classdef Butterworth_filter_saturation_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Butterworth_filter_saturation  matlab.ui.Figure
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ReadDataTab                    matlab.ui.container.Tab
        InputTimeUnitListBox           matlab.ui.control.ListBox
        InputTimeUnitListBoxLabel      matlab.ui.control.Label
        SelectUnitsListBox             matlab.ui.control.ListBox
        SelectUnitsListBoxLabel        matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        UpdateListButton               matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimeaccelLabel  matlab.ui.control.Label
        RawSRSTab                      matlab.ui.container.Tab
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        FrequencySpacingListBox        matlab.ui.control.ListBox
        FrequencySpacingListBoxLabel   matlab.ui.control.Label
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        MinFreqHzEditField             matlab.ui.control.NumericEditField
        MinFreqHzEditFieldLabel        matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        MaxFreqHzEditField             matlab.ui.control.NumericEditField
        MaxFreqHzEditFieldLabel        matlab.ui.control.Label
        PlottingLabel                  matlab.ui.control.Label
        CalculateButton_SRS            matlab.ui.control.Button
        SaturationRemovalTab           matlab.ui.container.Tab
        ReadOnlyBestFilterFrequencyHzEditField  matlab.ui.control.EditField
        ReadOnlyBestFilterFrequencyHzEditFieldLabel  matlab.ui.control.Label
        FadeOutRatioEditField          matlab.ui.control.EditField
        FadeOutRatioEditFieldLabel     matlab.ui.control.Label
        FadeInRatioEditField           matlab.ui.control.EditField
        FadeInRatioEditFieldLabel      matlab.ui.control.Label
        DesiredInitialSlopedBoctEditField  matlab.ui.control.EditField
        DesiredInitialSlopedBoctLabel  matlab.ui.control.Label
        HzEditField                    matlab.ui.control.EditField
        HzEditFieldLabel               matlab.ui.control.Label
        EnterthefrequencyabovewhichthedataisassumedtobegoodLabel  matlab.ui.control.Label
        CalculateButton_removal        matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SelectOutputListBox            matlab.ui.control.ListBox
        SelectOutputListBoxLabel       matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM     % Description
        raw_srs
        cleaned_srs
        cleaned_th
        iu
        t
        fn
        damp
        Q
        dt
        a1
        a2
        b1
        b2
        b3
        nspec
        last
        b_raw
        iflag
        fig_num
        max_ref
        min_ref
    end
    
    methods (Access = private)
        
        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end
        function TimeChoice(app)
            n=app.SignalProcessingListBox.Value;
            if(contains(n,'Entire'))
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditField.Visible='off'; 
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';                 
            else    
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';              
            end
        end
        function PreProcess(app)
        
            ClosePlots(app);
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                     
            ns=app.SignalProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);

        end
        
        function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            TimeChoice(app);
            app.ReadOnlyBestFilterFrequencyHzEditField.Value=sprintf(' ');
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Items = strtrim(app.SelectOutputListBox.Items);
            im = strtrim(app.SelectOutputListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.raw_srs; 
            end
            if(n==2)
                data=app.cleaned_srs;
            end
            if(n==3)
                data=app.cleaned_th; 
            end
              
            output_name=app.OutputArrayNameEditField.Value;
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete'); 
        end

        % Button pushed function: CalculateButton_removal
        function CalculateButton_removalPushed(app, event)

            good_freq=str2num(app.HzEditField.Value);

            app.b_raw=app.THM(:,2);
                
            a=app.b_raw;  
            app.b_raw=[];
                
            slope_goal=str2num(app.DesiredInitialSlopedBoctEditField.Value);
                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            record=1.0e+90;
            %
            disp(' ');
            %
            ioct=3;
            %
            [fc,imax]=octaves(ioct);
            %
            
            ffmax=good_freq*1.2;
            
            iphase=1;  % refiltering
            
            progressbar;

            disp('size a')
            size(a)
            
            for i=19:imax
            %    
                if(fc(i)> ffmax)
                    break;
                end
            
                fc1=fc(i);
                [yf]=Butterworth_LPF(a,app.dt,fc1,iphase);
                b=a-yf;

                if(i==19)
                    disp('size b')
                    size(b)
                end    
                
            %    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  SRS engine
            %

                try
                    [xmax,xmin,dmax,slope_p,slope_n,~,ppp,nnn,nnnoct]=...
                    vibrationdata_mr_SRS(app.dt,app.nspec,app.last,b,app.a1,app.a2,app.b1,app.b2,app.b3,app.fn,good_freq,app.max_ref,app.min_ref,slope_goal);
                catch
                    fprintf(' app.dt=%g  app.nspec=%d\n',app.dt,app.nspec);
                    warndlg('srs failed')
                    return;
                end
            %            
	            dbc=dmax;   % want to minimize
            %
	            slope_pc=slope_p;
	            slope_nc=slope_n;
            %
            %	total_db = ref_error + dbc + ( slope_pc + slope_nc );
                
	            total_db = dbc + ( slope_pc + slope_nc );
            
	            if( total_db<record )
            %
		            record=total_db;
            %
                    br=b;
                    yfr=yf;
                    fr=fc1;
                    
            %
                    ps=xmax;
                    pn=abs(xmin);
            %
            %
                    slope_pos=20.*log10(ppp)/nnnoct;
                    slope_neg=20.*log10(nnn)/nnnoct;        
                    
                    out1=sprintf(' %ld  err=%8.4g dB  fc=%8.4g Hz   +slope=%6.3g dB  -slope=%6.3g dB ',i,total_db,fc1,slope_pos,slope_neg);
                    disp(out1);        
            %
                end
            %
            end    
            
            b=br;
            
            %
            pause(0.5);
            progressbar(1);
            
            fprintf('\n Best case:  Frequency=%8.4g Hz \n\n',fr);  

            app.ReadOnlyBestFilterFrequencyHzEditField.Value=sprintf('%8.4g',fr);
            %    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.t=app.THM(:,1);
            app.t=fix_size(app.t);
            y=b;

            disp('size y')

            np=length(y);

            [vt]=integrate_function(y,app.dt);
            v=vt;
            
            
            nn=length(y);
            
            jj=round(0.9*nn);
            
            vrec=abs(mean(vt(jj:nn)));
            
            % fprintf('    %8.4g  \n',vrec);
            
            [~,np]=max(abs(y));
            
            fprintf(' np=%d   jj=%d  nn=%d \n',np,jj,nn);

            for i=6:np
            
                yt=b;
                [p,S,mu] = polyfit(app.t(1:i),b(1:i),3);
                
                [yp,~] = polyval(p,app.t(1:i),S,mu);
                
                yt(1:i)= yt(1:i) - yp;  
                
                [vt]=integrate_function(yt,app.dt);
                
                vm=abs(mean(vt(jj:nn)));
                
                if(vm<vrec)
                    vrec=vm;
                    v=vt;
                    b=yt;
            %        fprintf('   %d  %8.4g  %8.4g  \n',i,vm,vrec);
                end
                
            end

            n=length(v);
            
            fin=str2num(app.FadeInRatioEditField.Value);
            fout=str2num(app.FadeOutRatioEditField.Value);
            
            na=round(fin*n);
            nb=round(fout*n);
            
            delta=n-nb;
            %
            for i=1:na
                arg=pi*(( (i-1)/(na-1) )+1); 
                v(i)=v(i)*0.5*(1+(cos(arg)));
            end
            %
            for i=nb:n
                arg=pi*( (i-nb)/delta );
                v(i)=v(i)*(1+cos(arg))*0.5;
            end
            
            [b]=differentiate_function(v,app.dt);
            
            b=fix_size(b);
            
            yfr=a-b;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clear n;
            
            app.cleaned_th=[app.t b];
            
            %
            % setappdata(0,'cleaned_th',cleaned);
            %
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.t,yfr)
            grid on;
            title(' Saturation Estimate ');
            xlabel(' Time(sec) ');
            
            if(app.iu==1 || app.iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            
            %
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.t,b)
            grid on;
            title(' Acceleration Cleaned Signal ');
            xlabel(' Time(sec) ');
            if(app.iu==1 || app.iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            
            %
            if(app.iu==1)
                v=v*386;
            end
            if(app.iu==2)
                v=v*9.81;
            end
            %
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.t,v)
            grid on;
            title(' Velocity Cleaned Signal ');
            xlabel(' Time(sec) ');
            %
            if(app.iu==1)
                ylabel('Vel (in/sec)')
            else
                ylabel('Vel (m/sec)')    
            end
            %
            
            sz=max(size(xmax));
            fff=app.fn(1:sz);
            
            fff=fix_size(fff);
            
            f=fff;
            yy=b;
            
            [srs]=srs_function(yy,app.dt,app.damp,f);
            
            ps=srs(:,2);
            pn=srs(:,3);
            
            app.cleaned_srs=[fff ps pn];
            
            nff=length(fff);
            
            cleaned_abs_srs=zeros(nff,2);
            cleaned_abs_srs(:,1)=fff;
            
            for i=1:nff
                cleaned_abs_srs(i,2)=max([ ps(i) pn(i) ]);
            end
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(fff,ps,fff,abs(pn),'-.');
            legend ('positive','negative');
            out1=sprintf(' SRS Q=%g    Cleaned signal ',app.Q);   
            title(out1);
            xlabel(' Natural Frequency (Hz) ');
            if(app.iu==1 || app.iu==2)
                ylabel('Peak Accel (G)')
            else
                ylabel('Peak Accel (m/sec^2)')    
            end
            
            grid;
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log');
            %
            [xtt,xTT,app.iflag]=xtick_label(fff(1),fff(end));
            
            if(app.iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
                fmin=min(xtt);
                fmax=max(xtt); 
                xlim([fmin fmax]);
            end
            
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)

            TimeChoice(app);
        end

        % Button pushed function: CalculateButton_SRS
        function CalculateButton_SRSPushed(app, event)
            app.ReadOnlyBestFilterFrequencyHzEditField.Value=sprintf(' ');
            PreProcess(app);

            app.fig_num=1;

            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            
            if(iflagq~=1)
	            return
            end
            
            res=2;

            Items = char(app.FrequencySpacingListBox.Items);
            im=app.FrequencySpacingListBox.Value;
            [fspace]=find_index(Items,im);
            
            Items = char(app.SelectUnitsListBox.Items);
            im=app.SelectUnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            Items = char(app.InputTimeUnitListBox.Items);
            im=app.InputTimeUnitListBox.Value;
            [itu]=find_index(Items,im);
     
            tor=app.THM(:,1);
            app.last=length(tor);
            
            if(itu==2)
               app.THM(:,1)=app.THM(:,1)/1000; 
            end    
            
            y=double(app.THM(:,2));
            yy=y;
            
            app.dt=mean(diff(app.THM(:,1)));
            sr=1/app.dt;

            app.Q=app.AmplificationFactorQEditField.Value;
            app.Q=str2num(app.Q);

            fstart=app.MinFreqHzEditField.Value;
              fend=app.MaxFreqHzEditField.Value;
              
            if(isempty(fstart))
               warndlg('Enter Min Plot Frequency');
               return;
            end
            if(isempty(fend))
               warndlg('Enter Max Plot Frequency');
               return;
            end

            if(fstart>=fend)
               warndlg('Frequency Limit Error');
               return; 
            end
              
            if(fstart==0)
               fstart=0.1; 
            end
                          
            app.damp=1/(2*app.Q);
            
            fmax=sr/5;
            
            [app.fn,num]=srs_fn(fspace,fstart,fend,fmax);

            if(isempty(app.fn))
                warndlg('Check sample rate & frequency limits');
                return;
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            
            try
                [app.a1,app.a2,app.b1,app.b2,app.b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(app.fn,app.damp,app.dt);                          
            catch
                warndlg('srs_coefficients failed');
                return;
            end
            %
            %  SRS engine
            %
            
            a_rms=zeros(num,1);
            a_pos=zeros(num,1);
            a_neg=zeros(num,1);
            % rd_pos=zeros(num,1);
            % rd_neg=zeros(num,1);
            % pv_pos=zeros(num,1);
            % pv_neg=zeros(num,1);
            
            NL=length(yy);
            
            progressbar;
            for j=1:num
            %
                progressbar(j/num); 
            %
                if(res==1)
                    ML=NL+round((1/app.fn(j))/app.dt);
                    ys=zeros(ML,1);
                    ys(1:NL)=yy;
                else
                    ys=yy;
                end
            %  
                [~,a_pos(j),a_neg(j),a_rms(j)]=arbit_engine_srs_vrs(app.a1(j),app.a2(j),app.b1(j),app.b2(j),app.b3(j),ys);
            %
            %    [~,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),ys);
            %
            %    if(app.iu==1)
            %        scale_rd=386;
            %    else    
            %        scale_rd=9.81*1000;                
            %    end
                
            %    rd_pos(j)=rd_pos(j)*scale_rd;
            %    rd_neg(j)=rd_neg(j)*scale_rd;        
            %
            %    omegan=2*pi*app.fn(j);
            %    pv_pos(j)=omegan*rd_pos(j);
            %    pv_neg(j)=omegan*rd_neg(j);    
            %
            %    if(app.iu==2)
            %        pv_pos(j)=pv_pos(j)/10;
            %        pv_neg(j)=pv_neg(j)/10;
            %    end
            %
            end
            %
            pause(0.3);
            progressbar(1);
            %
            a_neg=abs(a_neg);
            % pv_neg=abs(pv_neg);
            % rd_neg=abs(rd_neg);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            app.fn=fix_size(app.fn);
            a_pos=fix_size(a_pos);
            a_neg=fix_size(a_neg);
            % pv_pos=fix_size(pv_pos);
            % pv_neg=fix_size(pv_neg);
            % rd_pos=fix_size(rd_pos);
            % rd_neg=fix_size(rd_neg);
            %
            app.raw_srs=[app.fn a_pos a_neg];
            % app.p_vel_data=[fn pv_pos pv_neg];
            % app.rel_disp_data=[fn rd_pos rd_neg];
            %
            h=figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(tor,app.THM(:,2));
            title('Base Input Acceleration Time History');
            
            if(itu==1)
                xlabel(' Time (sec) ')
            else
                xlabel(' Time (msec) ')    
            end
            
            if(app.iu==1 || app.iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            grid on;
            
            fmin=fstart;
            fmax=fend;
            
            t_string=sprintf('Acceleration SRS Q=%g',app.Q);
            
            if(app.iu==1 || app.iu==2)
                y_lab='Peak Accel (G)';
            else
                y_lab='Peak Accel (m/sec^2)';    
            end
            
            [app.fig_num,~]=srs_plot_function_h(app.fig_num,app.fn,a_pos,a_neg,t_string,y_lab,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.nspec=length(app.fn);

            xmax=a_pos;
            xmin=a_neg;
        
            for j=app.nspec:-1:2    
        %
        %        printf(" f=%8.4g Hz  xmax=%8.4g  xmin=%8.4g \n",f(j),xmax(j),xmin(j)); 
        %
                if( xmax(j)>0 && abs(xmin(j))>0)
        %       
                    aaa=abs(20*log10(  xmax(j)/abs(xmin(j)  ) ) );
                    bbb=abs(20*log10(xmax(j-1)/abs(xmin(j-1)) ) );
        %
        %           printf("      aaa=%8.4g  bbb=%8.4g \n",aaa,bbb); 
        %
                    if(aaa>=4. && bbb>=4.)
        %
                        out1=sprintf('%8.4g ',app.fn(j-1));
                        % set(handles.edit_good_frequency,'String',out1);
                        app.HzEditField.Value=out1;
                        break;
                    end
                end
            end

            b=y;
            app.nspec=length(app.fn);
            good_freq=1.0e+20;
            slope_goal=6.;
            app.max_ref=zeros(app.nspec,1);
            app.min_ref=zeros(app.nspec,1);
            %
            [xmax,xmin,~,~,~,~,~,~,~]=...
                vibrationdata_mr_SRS(app.dt,app.nspec,app.last,b,app.a1,app.a2,app.b1,app.b2,app.b3,app.fn,good_freq,app.max_ref,app.min_ref,slope_goal);
            %
            %
            app.max_ref=xmax;
            app.min_ref=xmin;

   
        end

        % Size changed function: SaturationRemovalTab
        function SaturationRemovalTabSizeChanged(app, event)
            position = app.SaturationRemovalTab.Position;
            
        end

        % Value changing function: DesiredInitialSlopedBoctEditField
        function DesiredInitialSlopedBoctEditFieldValueChanging(app, event)
            
            app.ReadOnlyBestFilterFrequencyHzEditField.Value=sprintf(' ');
        end

        % Value changing function: HzEditField
        function HzEditFieldValueChanging(app, event)

            app.ReadOnlyBestFilterFrequencyHzEditField.Value=sprintf(' ');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Butterworth_filter_saturation = uifigure('Visible', 'off');
            app.Butterworth_filter_saturation.Name = 'Butterworth Filter Saturation';
            app.ReturnButton = uibutton(app.Butterworth_filter_saturation, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.Butterworth_filter_saturation);
            app.ReadDataTab = uitab(app.TabGroup);
            app.ReadDataTab.Title = 'Read Data';
            app.TheinputarraymusthavetwocolumnstimeaccelLabel = uilabel(app.ReadDataTab);
            app.TheinputarraymusthavetwocolumnstimeaccelLabel.Text = 'The input array must have two columns: time & accel';
            app.UpdateListButton = uibutton(app.ReadDataTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.listbox_method = uilistbox(app.ReadDataTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ReadDataTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.SelectInputArrayListBoxLabel = uilabel(app.ReadDataTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ReadDataTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectUnitsListBoxLabel = uilabel(app.ReadDataTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.ReadDataTab);
            app.SelectUnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm', 'm/sec^2, m/sec, mm', ''};
            app.SelectUnitsListBox.Value = 'G, in/sec, in';
            app.InputTimeUnitListBoxLabel = uilabel(app.ReadDataTab);
            app.InputTimeUnitListBoxLabel.Text = 'Input Time Unit';
            app.InputTimeUnitListBox = uilistbox(app.ReadDataTab);
            app.InputTimeUnitListBox.Items = {'sec', 'msec'};
            app.InputTimeUnitListBox.Value = 'sec';
            app.RawSRSTab = uitab(app.TabGroup);
            app.RawSRSTab.Title = 'Raw SRS';
            app.CalculateButton_SRS = uibutton(app.RawSRSTab, 'push');
            app.CalculateButton_SRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SRSPushed, true);
            app.CalculateButton_SRS.Text = 'Calculate';
            app.PlottingLabel = uilabel(app.RawSRSTab);
            app.PlottingLabel.Text = 'Plotting';
            app.MaxFreqHzEditFieldLabel = uilabel(app.RawSRSTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.RawSRSTab, 'numeric');
            app.MaxFreqHzEditField.Value = 10000;
            app.StartTimesecEditFieldLabel = uilabel(app.RawSRSTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.RawSRSTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.RawSRSTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.RawSRSTab, 'numeric');
            app.MinFreqHzEditFieldLabel = uilabel(app.RawSRSTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.RawSRSTab, 'numeric');
            app.MinFreqHzEditField.Value = 10;
            app.AmplificationFactorQEditFieldLabel = uilabel(app.RawSRSTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.RawSRSTab, 'text');
            app.AmplificationFactorQEditField.Value = '10';
            app.FrequencySpacingListBoxLabel = uilabel(app.RawSRSTab);
            app.FrequencySpacingListBoxLabel.Text = 'Frequency Spacing';
            app.FrequencySpacingListBox = uilistbox(app.RawSRSTab);
            app.FrequencySpacingListBox.Items = {'1/12 octave', '1/24 octave', '1 Hz constant', '0.5 Hz constant'};
            app.FrequencySpacingListBox.Value = '1/12 octave';
            app.SignalProcessingListBoxLabel = uilabel(app.RawSRSTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.RawSRSTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.SaturationRemovalTab = uitab(app.TabGroup);
            app.SaturationRemovalTab.SizeChangedFcn = createCallbackFcn(app, @SaturationRemovalTabSizeChanged, true);
            app.SaturationRemovalTab.Title = 'Saturation Removal';
            app.CalculateButton_removal = uibutton(app.SaturationRemovalTab, 'push');
            app.CalculateButton_removal.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_removalPushed, true);
            app.CalculateButton_removal.Text = 'Calculate';
            app.EnterthefrequencyabovewhichthedataisassumedtobegoodLabel = uilabel(app.SaturationRemovalTab);
            app.EnterthefrequencyabovewhichthedataisassumedtobegoodLabel.Text = {'Enter the frequency above which '; 'the data is assumed to be good.'};
            app.HzEditFieldLabel = uilabel(app.SaturationRemovalTab);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.SaturationRemovalTab, 'text');
            app.HzEditField.ValueChangingFcn = createCallbackFcn(app, @HzEditFieldValueChanging, true);
            app.DesiredInitialSlopedBoctLabel = uilabel(app.SaturationRemovalTab);
            app.DesiredInitialSlopedBoctLabel.Text = {'Desired Initial Slope '; '(dB/oct)'};
            app.DesiredInitialSlopedBoctEditField = uieditfield(app.SaturationRemovalTab, 'text');
            app.DesiredInitialSlopedBoctEditField.ValueChangingFcn = createCallbackFcn(app, @DesiredInitialSlopedBoctEditFieldValueChanging, true);
            app.FadeInRatioEditFieldLabel = uilabel(app.SaturationRemovalTab);
            app.FadeInRatioEditFieldLabel.Text = 'Fade In Ratio';
            app.FadeInRatioEditField = uieditfield(app.SaturationRemovalTab, 'text');
            app.FadeInRatioEditField.Value = '0.01';
            app.FadeOutRatioEditFieldLabel = uilabel(app.SaturationRemovalTab);
            app.FadeOutRatioEditFieldLabel.Text = 'Fade Out Ratio';
            app.FadeOutRatioEditField = uieditfield(app.SaturationRemovalTab, 'text');
            app.FadeOutRatioEditField.Value = '0.03';
            app.ReadOnlyBestFilterFrequencyHzEditFieldLabel = uilabel(app.SaturationRemovalTab);
            app.ReadOnlyBestFilterFrequencyHzEditFieldLabel.Text = {'Read Only'; 'Best Filter Frequency (Hz) '};
            app.ReadOnlyBestFilterFrequencyHzEditField = uieditfield(app.SaturationRemovalTab, 'text');
            app.ReadOnlyBestFilterFrequencyHzEditField.Editable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SelectOutputListBoxLabel = uilabel(app.ExportTab);
            app.SelectOutputListBoxLabel.Text = 'Select Output';
            app.SelectOutputListBox = uilistbox(app.ExportTab);
            app.SelectOutputListBox.Items = {'Raw Accel SRS', 'Cleaned Accel SRS', 'Cleaned Accel Time History', ''};
            app.SelectOutputListBox.Value = 'Raw Accel SRS';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.Butterworth_filter_saturation);
            app.Label.Text = {'Butterworth_filter_saturation.m   ver 1.5  by Tom Irvine'; ''; 'Shock response spectrum for an SDOF system subjected to base excitation with saturation removal.'};
            % Show the figure after all components are created
            app.Butterworth_filter_saturation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Butterworth_filter_saturation_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Butterworth_filter_saturation)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Butterworth_filter_saturation)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Butterworth_filter_saturation)
        end
    end
end