classdef FDS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MsgEditField                    matlab.ui.control.EditField
        MsgEditFieldLabel               matlab.ui.control.Label
        TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel  matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        SetSaveExportFolderButton       matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        FDS_Appver10byTomIrvineFatigueDamageSpectrumLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ImportTimeHistoryArraysTab      matlab.ui.container.Tab
        UpdateListButton                matlab.ui.control.Button
        CurrentWorkspaceListBox         matlab.ui.control.ListBox
        CurrentWorkspaceListBoxLabel    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ReadNewFileButton               matlab.ui.control.Button
        EachinputarraymusthavetwocolumnstimesecaccelorpressureLabel  matlab.ui.control.Label
        FDSTab                          matlab.ui.container.Tab
        TimeHistoryDurationScaleFactorEditField  matlab.ui.control.EditField
        TimeHistoryDurationScaleFactorLabel  matlab.ui.control.Label
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        OutputFDSTypeListBox            matlab.ui.control.ListBox
        OutputFDSTypeListBoxLabel       matlab.ui.control.Label
        ExportFDSOutputButton           matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        OutputArrayNamesTextArea        matlab.ui.control.TextArea
        OutputArrayNamesTextAreaLabel   matlab.ui.control.Label
        SelectoneormorearraynamesLabel  matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.EditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.EditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        UpdateTableButton               matlab.ui.control.Button
        UITable_TH                      matlab.ui.control.Table
        LoadModelButton                 matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        UITable_b                       matlab.ui.control.Table
        UITable_Q                       matlab.ui.control.Table
        NumberofbCasesListBox           matlab.ui.control.ListBox
        NumberofbCasesListBoxLabel      matlab.ui.control.Label
        NumberofQCasesListBox           matlab.ui.control.ListBox
        NumberofQCasesListBoxLabel      matlab.ui.control.Label
        OctaveSpacingListBox            matlab.ui.control.ListBox
        OctaveSpacingListBoxLabel       matlab.ui.control.Label
        MaxFreqHzEditField              matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel         matlab.ui.control.Label
        MinFreqHzEditField              matlab.ui.control.EditField
        MinFreqHzEditFieldLabel         matlab.ui.control.Label
        PSDEnvelopeviaFDSTab            matlab.ui.container.Tab
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        IncludeSMCS016MinimumWorkmanshipCheckBox  matlab.ui.control.CheckBox
        SaveModelButton_env             matlab.ui.control.Button
        ExportOutputsButton             matlab.ui.control.Button
        ExtractQbfromArrayNameButton    matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        UITable_FDS_PSD                 matlab.ui.control.Table
        OptimizedPSDOuputNameInternalExternalEditField  matlab.ui.control.EditField
        OptimizedPSDOuputNameInternalExternalLabel  matlab.ui.control.Label
        PlateauMinimumOctaveSpacingListBox  matlab.ui.control.ListBox
        PlateauMinimumOctaveSpacingLabel  matlab.ui.control.Label
        OptimizationGoalListBox         matlab.ui.control.ListBox
        OptimizationGoalListBoxLabel    matlab.ui.control.Label
        InputFDSTypeListBox             matlab.ui.control.ListBox
        InputFDSTypeListBoxLabel        matlab.ui.control.Label
        NumberofPSDCoordinatesListBox   matlab.ui.control.ListBox
        NumberofPSDCoordinatesListBoxLabel  matlab.ui.control.Label
        OutputPSDUnitListBox            matlab.ui.control.ListBox
        OutputPSDUnitListBoxLabel       matlab.ui.control.Label
        MaxSlopedBoctEditField          matlab.ui.control.EditField
        MaxSlopedBoctEditFieldLabel     matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        PSDDurationsecEditField         matlab.ui.control.EditField
        PSDDurationsecEditFieldLabel    matlab.ui.control.Label
        NumberofFDSArraysListBox        matlab.ui.control.ListBox
        NumberofFDSArraysLabel          matlab.ui.control.Label
        LoadModelButton_env             matlab.ui.control.Button
        DoubleclickonArrayNametoaccesspulldownmenuLabel_2  matlab.ui.control.Label
        CalculateButton_env             matlab.ui.control.Button
    end

    
    properties (Access = private)
        nQ    % Description
        nb    %
        num   % 
        msz   %
        iflag %
        kflag %
        THM   %
        save_name %
        fmin  %
        fmax  %f
        start_time %
        end_time   % 
        bex      %
        Q        %
        number   %
        Octave_Items  %
        ATH      %
        filename %
        name     %
        array_name %
        nfilename  %
        Oct_im   %
        oxx      %
        AData    %
        npb      %
        goal     %
        ip       %
        iu       %
        iu_th    %
        nmetric  %
        metric   %
        ntrials  %
        T_out    %
        slopec   %
        save_FDS_PSD_name  %
        power_spectral_density  %  
        FDS_name %
        big      % 
        neFDS    %
    end
    
    methods (Access = private)

        function update(app)
            [cell_array]=update_two_columns();
            app.CurrentWorkspaceListBox.Items=unique(sort(cell_array)); 
            app.CurrentWorkspaceListBox.Value=app.CurrentWorkspaceListBox.Items{1};    

            sz=size(cell_array);

            n=sz(1);

            for i=1:n
                cx{i,1}=char(cell_array(i));
                pp{i,1}=false;
            end

            app.UITable_TH.ColumnFormat={'char','logical'};
            tdata = table(cx,pp,'VariableNames',{'Time History Array Name','Select'});
            app.UITable_TH.Data=tdata;

        end             

        function eFDS(app)

            Items = char(app.InputFDSTypeListBox.Items);
            im=app.InputFDSTypeListBox.Value;
            [app.neFDS]=find_index(Items,im);

            if(app.neFDS==1)
                ss{1}='G^2/Hz';
            end    
            if(app.neFDS==2)
                ss{1}='(in/sec)^2/Hz';
                ss{2}='(m/sec)^2/Hz';            
            end    
            if(app.neFDS==3)
                ss{1}='in^2/Hz';
                ss{2}='m^2/Hz';            
            end    
            if(app.neFDS==4)
                ss{1}='psi^2/Hz';
                ss{2}='Pa^2/Hz';            
            end  

            app.OutputPSDUnitListBox.Items=ss;

        end    

        function cleartext(app)            
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.ExportOutputsButton.Visible='off';
            app.OutputArrayNamesTextArea.Value=' ';
        end    

        function extractbQ(app)
 
            N=str2num(app.NumberofFDSArraysListBox.Value);
            app.AData=app.UITable_FDS_PSD.Data;

            G=app.AData;

            GG=table2cell(G);

            for i=1:N
                app.FDS_name{i}=char(GG{i,1});
            end

            sq{1}=sprintf(' ');
            sb{1}=sprintf(' ');
            for i=2:N
                sq{end+1}=sprintf(' ');
                sb{end+1}=sprintf(' ');
            end
            sq=sq';
            sb=sb';

            for i=1:N
                for j=100:-1:1
                    ss=sprintf('Q%d',j);
                    if(contains(app.FDS_name{i},ss))
                        ss=sprintf('%d',j);
                        G{i,2}={ss};
                        break;
                    end    
                end
            end    

            for i=1:N
                for j=200:-1:1
                    jj=j/10;
                    ss=sprintf('b%g',jj);
                    if(contains(app.FDS_name{i},ss))
                        ss=sprintf('%d',jj);
                        G{i,3}={ss};
                        break;
                    end    
                end
            end                

            try
                app.UITable_FDS_PSD.Data=G;  
            catch
                warndlg('Table failed')
                return;
            end    
        end
        function ChangeRows_FDS_PSD(app)

            disp('ChangeRows');

            ns=app.NumberofFDSArraysListBox.Value;
            num_arrays=str2num(ns); 

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || ...
                    contains(vars(i).name,'vname_list') || ...
                    contains(vars(i).name,'amp_cycles') || ...
                    contains(vars(i).name,'range_cycles') || ...
                    contains(vars(i).name,'amp_mean_cycles') || ...
                    contains(vars(i).name,'range_cycles_max_min'))
                    cell_array{i} ='999';
                else  

                    try   
                        app.THM=evalin('base',vars(i).name);
                        sz=size(app.THM);

                        if(sz(2)==2 && contains(vars(i).name,'fds_Q'))
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            try
                ccc=unique(sort(cell_array)); 
                cnames = categorical(ccc);
                sz=size(ccc);
                app.msz=max(sz);
            catch
                app.msz=0;
            end
            
            app.kflag=0;
            nflag=0;

            if(sz(1)==0 || sz(2)==0)
                app.kflag=1;
                % warndlg('Ref 1:  No arrays available');
                return;
            end             
            
            if(app.msz<num_arrays)
                num_arrays=app.msz;
                try
                    app.NumberofFDSArraysListBox.Value=sprintf('%d',num_arrays);
                    warndlg('Number of curves reset to available number');
                catch
                end
            end 

            sq{1}=sprintf(' ');
            sb{1}=sprintf(' ');
            for i=2:num_arrays
                sq{end+1}=sprintf(' ');
                sb{end+1}=sprintf(' ');
            end
            sq=sq';
            sb=sb';

            if(num_arrays==1)
                try
                    n=1;
                    for i=1:app.msz
                        if(strcmp(app.name(1),char(cnames(i)))==1)
                            n=i;
                            nflag=1;
                            break;
                        end    
                    end    
                catch    
                end    
                cx={cnames(n)};       
            end    
            if(num_arrays==2)
                try
                    n=ones(2,1);
                    for ijk=1:2
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2))};    
            end   
            if(num_arrays==3)
                cx={cnames(end);cnames(end);cnames(end)};     
            end    
            
            if(num_arrays==4)
                try
                    n=ones(4,1);
                    for ijk=1:4
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2));cnames(n(3));cnames(n(4))};
            end     
            if(num_arrays==5)
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};
            end       
            if(num_arrays==6)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};          
            end 
            if(num_arrays==7)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};             
            end             
            if(num_arrays==8)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};              
            end  
            if(num_arrays==9)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};              
            end  

            app.UITable_FDS_PSD.ColumnWidth={235,80,80};           

            try
                tdata = table(cx,sq,sb,'VariableNames',{'FDS Array Name','Q','b'});
            catch

                fprintf('\n  app.num=%d \n',app.num)
                warndlg('Change Rows: No arrays found');
            end

            try
                app.UITable_FDS_PSD.Data=tdata;  
            catch
                warndlg('Table failed')
                return;
            end

            if(nflag==1)
                extractbQ(app);
            end

        end

        function ChangeRows(app)
            update(app);
        end

        function update_list(app)
            [cell_array]=update_two_columns();
            app.CurrentWorkspaceListBox.Items=unique(sort(cell_array)); 
        end            
        
        function ClosePlots(app)
            for i=1:120
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end       

        function ChangeQ(app)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.nQ=str2num(app.NumberofQCasesListBox.Value);
            if(app.nQ==1)
                q=10;
            else
                q=[10;30];
            end             
            app.UITable_Q.Data=q;  
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_Q,s) % adjust the style           
        end
        function Changeb(app)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.nb=str2num(app.NumberofbCasesListBox.Value);    
            if(app.nb==1)
                q=4;
            else
                q=[4;8];
            end    
            app.UITable_b.Data=q;           
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_b,s) % adjust the style              
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_list(app);
            ClosePlots(app);
            ChangeQ(app);
            Changeb(app);
            % ChangeRows(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.ExportFDSOutputButton.Visible='off';
            app.ExportOutputsButton.Visible='off';
            eFDS(app);

            try
                if(isdeployed)
                else    
                    app.FolderEditField.Value=pwd;
                end
            catch
            end

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: NumberofQCasesListBox
        function NumberofQCasesListBoxValueChanged(app, event)
            ChangeQ(app);      
        end

        % Value changed function: NumberofbCasesListBox
        function NumberofbCasesListBoxValueChanged(app, event)
            Changeb(app);
        end

        % Button pushed function: ReadNewFileButton
        function ReadNewFileButtonPushed(app, event)
            try
            
                   [app.filename, pathname] = uigetfile('*.*');
                   app.filename = fullfile(pathname, app.filename);

                   app.THM = readmatrix(app.filename);

                   [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
                   if(iflagq~=1)
	                    return
                   end

                   msgbox('File read');              

            catch
                   warndlg('Read unsuccessful');
                   return;
            end

            try
                    [~, name, ~] = fileparts(app.filename);
            catch
                    warndlg('ref 1')
            end

            try
                    assignin('base',name,app.THM);
            catch
                    warndlg('ref 2')
            end

            update_list(app);
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            update_list(app);
            ChangeRows(app);
        end

        % Callback function
        function NumberofArraysListBoxValueChanged(app, event)
            ChangeRows(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            n=app.TabGroup.SelectedTab;

            if(strcmp(n.Title,'FDS'))
                ChangeRows(app);
            end    
            if(strcmp(n.Title,'PSD Envelope via FDS'))
                ChangeRows_FDS_PSD(app); 
            end    
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cleartext(app);
            ClosePlots(app);

            Items = char(app.SelectUnitsListBox.Items);
            im=app.SelectUnitsListBox.Value;
            [app.iu_th]=find_index(Items,im);            

            Items = char(app.OutputFDSTypeListBox.Items);
            im=app.OutputFDSTypeListBox.Value;
            [nFDS]=find_index(Items,im);

            app.InputFDSTypeListBox.Value=app.InputFDSTypeListBox.Items{nFDS}; 

            fig_num=1;

            A=app.UITable_TH.Data;
            A=table2cell(A);

            sz=size(A);
            N=sz(1);

            clear app.array_name; 

            k=1;
            j=1;
            
            for i=1:N
                array{i}=char(A{k}); k=k+1;
            end
            for i=1:N
                pp{i}=A{k}; k=k+1;
                
                if(pp{i}==1)
                    app.array_name{j}=array{i};
                    j=j+1;
                end    
            end

            if(j==1)
                warndlg('Select Array');
                return;
            end    

            num_arrays=length(app.array_name);


            A=app.UITable_Q.Data;
            for i=1:app.nQ
                app.Q(i)=A(i);
            end

            A=app.UITable_b.Data;
            for i=1:app.nb
                app.bex(i)=A(i);
            end

            app.fmin=str2num(app.MinFreqHzEditField.Value);
            app.fmax=str2num(app.MaxFreqHzEditField.Value);

            app.start_time=str2num(app.StartTimesecEditField.Value);
            app.end_time=str2num(app.EndTimesecEditField.Value);         

            iscale=str2num(app.TimeHistoryDurationScaleFactorEditField.Value);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.Octave_Items = char(app.OctaveSpacingListBox.Items);
            app.Oct_im=app.OctaveSpacingListBox.Value;
            [app.oxx]=find_index(app.Octave_Items,app.Oct_im);
            
            ioct=1+app.oxx;
            
            [fn,~]=octaves(ioct);
            
            [~,i1]=min(abs(fn-app.fmin));
            [~,i2]=min(abs(fn-app.fmax));
            
            fn=fn(i1:i2);
                  
            nc=app.nQ*app.nb;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            kx=1;
            
            for ij=1:app.nQ
                for ik=1:app.nb
                    suffix{kx}=sprintf('fds_Q%g_b%g',app.Q(ij),app.bex(ik));
                    kx=kx+1;
                end
            end
            
            nk=1;
  
            for i=1:num_arrays  % array
             
                fds_th=zeros(length(fn),nc);
                qqq=zeros(nc,1);
                FS=app.array_name{i};
               
                kv=1;
                
                for ij=1:app.nQ
                
                    for ik=1:app.nb

                        if(nFDS==1)
                            app.name{i,kv}=sprintf('%s_ac_fds_Q%g_b%g',FS,app.Q(ij),app.bex(ik));
                        end
                        if(nFDS==2)
                            app.name{i,kv}=sprintf('%s_pv_fds_Q%g_b%g',FS,app.Q(ij),app.bex(ik));
                        end
                        if(nFDS==3)
                            app.name{i,kv}=sprintf('%s_rd_fds_Q%g_b%g',FS,app.Q(ij),app.bex(ik));
                        end
                        if(nFDS==4)
                            app.name{i,kv}=sprintf('%s_pt_fds_Q%g_b%g',FS,app.Q(ij),app.bex(ik));
                        end

                        app.name{i,kv} = strrep(app.name{i,kv},'.','p');
                        
                        try
                            app.THM=evalin('base',app.name{i,kv});
                            
                            try
                                fds_th(:,kv)=app.THM(:,2);
                            catch
                                fds_th=zeros(length(fn),nc);
                            end    
                            
                            qqq(kv)=1;
                            
                            if(length(fn)~=length(app.THM(:,1)))
                                qqq(kv)=0;
                            end                            
                        catch
                            qqq(kv)=0;
                        end  
            
                        qqq(kv)=0;
    
                        nk=nk+1;
                        kv=kv+1;            
                    end   
                end

            %%%%
                
                if(min(qqq)==0)
                    try
                        FS=app.array_name{i};
                        app.THM=evalin('base',FS);
                        disp('pass')
                    catch
                        disp('fail')
                    end

                    [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
                    if(iflagq~=1)
	                    return
                    end
                      
                    try
                        [~,istart]=min(abs(app.start_time-app.THM(:,1)));      
                        [~,iend]=min(abs(app.end_time-app.THM(:,1)));      
                   
                        app.THM=app.THM(istart:iend,:); 

                        tim=double(app.THM(:,1));
                        t=tim;
                 
                        difft=diff(t);
                        dt=mean(difft);
                    catch
                        warndlg('Time check 1 failed');
                        return;
                    end        
                    try         
                        [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
                    catch
                        warndlg('Time check 2 failed');
                        return;
                    end    
   
                    yy1=app.THM(:,2);
                    
                    if(max(abs(yy1))<1.0e-20)
                        warndlg('Array has zero amplitude');
                        return;
                    end

                    try
                        [fds_th1]=fds_engine_apvrd(fn,app.Q,app.bex,yy1,dt,app.iu_th,nFDS);
                        fds_th1=iscale*fds_th1;
                    catch
                        warndlg('fds_engine failed');
                        return;
                    end    
                        
                    clear yy1;
                      
                end 

            %  Export Data
                 
                kv=1;
                
                if(min(qqq)==0)
                    
                    for ij=1:app.nQ
                        for ik=1:app.nb
                            
                            nnn=sprintf('%s',app.name{i,kv});

                            data=[fn fds_th1(:,kv)];
                            assignin('base',nnn,data);
                            % writematrix(data,nnn,'Delimiter','\t')
            
                            kv=kv+1;
                        end  
                    end

                    try

                        if(nFDS==1) % accel
                            app.metric=1;
                        end    
                        if(nFDS==2) % pv
                            app.metric=2;                        
                        end       
                        if(nFDS==3) % rel disp
                            app.metric=3;                
                        end               
                        if(nFDS==4) % trans pressure
                            app.metric=4;                    
                        end             


                        ffmin=fn(1);
                        ffmax=fn(end);
                        
                        x_label='Natural Frequency (Hz)';
                        
                        fds=[fn fds_th1];
    
                        FS=strrep(FS,'_',' ');

                        if(app.nQ==1 && app.nb==1)
                            [fig_num,hp]=plot_fds_title_function_altf(fig_num,x_label,...
                                fds,ffmin,ffmax,app.metric,app.Q,app.bex,FS,app.iu_th);
                            app.NumberofFDSArraysListBox.Value='1';
                        end
                        
                        if(app.nQ*app.nb==2)
                            [fig_num,hp]=plot_fds_1x2_title_function_alt(fig_num,x_label,...
                                fds,ffmin,ffmax,app.metric,app.Q,app.bex,FS,app.iu_th);
                            app.NumberofFDSArraysListBox.Value='2';
                            set(hp, 'Position', [0 0 1000 450]);
                        end    
        
                        if(app.nQ==2 && app.nb==2)    
                            [fig_num,hp]=plot_fds_2x2_title_function_alt(fig_num,x_label,fds,...
                                ffmin,ffmax,app.metric,app.Q,app.bex,FS,app.iu_th);
                            app.NumberofFDSArraysListBox.Value='4';
                            set(hp, 'Position', [0 0 1050 650]);
                        end

                    catch
                        warndlg('Plot failed');
                        return;
                    end    

                end
             
            end
            
            for i=1:num_arrays
                for kv=1:nc

                    if(i==1 && kv==1)
                        ss=sprintf('%s',app.name{1,1});
                    else    
                        nnn=sprintf('%s',app.name{i,kv});
                        ss=sprintf('%s\n%s',ss,nnn);
                    end

                end
            end    

            app.number=num_arrays;

            app.OutputArrayNamesTextArea.Value=ss;
            app.OutputArrayNamesTextArea.Visible='on';
            app.OutputArrayNamesTextAreaLabel.Visible='on';
            app.ExportFDSOutputButton.Visible='on';

            disp('goto ChangeRows_FDS_PSD');
            ChangeRows_FDS_PSD(app);

            eFDS(app);

        end

        % Value changed function: OctaveSpacingListBox
        function OctaveSpacingListBoxValueChanged(app, event)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.ExportFDSOutputButton.Visible='off';
        end

        % Value changing function: StartTimesecEditField
        function StartTimesecEditFieldValueChanging(app, event)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.ExportFDSOutputButton.Visible='off';
        end

        % Value changing function: EndTimesecEditField
        function EndTimesecEditFieldValueChanging(app, event)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.ExportFDSOutputButton.Visible='off';
        end

        % Value changing function: MinFreqHzEditField
        function MinFreqHzEditFieldValueChanging(app, event)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.ExportFDSOutputButton.Visible='off';
        end

        % Value changing function: MaxFreqHzEditField
        function MaxFreqHzEditFieldValueChanging(app, event)
            app.OutputArrayNamesTextArea.Visible='off';
            app.OutputArrayNamesTextAreaLabel.Visible='off';
            app.ExportFDSOutputButton.Visible='off';
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    SetSaveExportFolderButtonPushed(app, event);
                end    

            catch
                SetSaveExportFolderButtonPushed(app, event);
            end
            
            try
                ss=sprintf('%s\\*.mat',writepname);
                ff = uigetfile(ss, 'Select load model file');
                NAME=char(fullfile(writepname,ff));
            catch
                [ff, writepname] = uigetfile('*.mat', 'Select load model file');
                NAME=[writepname,ff];
            end

            try
                struct=load(NAME);
            catch
                NAME
                warndlg('load failed');
                return;
            end    
            
     
            structnames = fieldnames(struct, '-full'); % fields in the struct
            
            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
               FDSMultiple=evalin('base','FDSMultiple');
            catch
               warndlg(' evalin failed 1');
               return;
            end
            
            %%%%%%%%%%%%
            
            try 
                app.FolderEditField.Value=FDSMultiple.writepname;
            catch
            end 
           
            try 
                app.MinFreqHzEditField.Value=sprintf('%g',app.FDSMultiple.fmin);      
            catch
            end 
            try 
                app.MaxFreqHzEditField.Value=sprintf('%g',FDSMultiple.fmax); 
            catch
            end  

            try 
                app.StartTimesecEditField.Value=sprintf('%g',FDSMultiple.start_time);
            catch
            end 
            try 
                app.EndTimesecEditField.Value=sprintf('%g',FDSMultiple.end_time);  
            catch
            end  

            try 
                app.NumberofQCasesListBox.Value=sprintf('%d',FDSMultiple.nQ);
            catch
            end  
            try 
                app.NumberofbCasesListBox.Value=sprintf('%d',FDSMultiple.nb);
            catch
            end 

            Changeb(app);
            ChangeQ(app);

            try 
                app.UITable_Q.Data=fix_size(FDSMultiple.Q);
            catch
            end  
            try 
                app.UITable_b.Data=fix_size(FDSMultiple.bex);
            catch
            end  

            try 
                app.OctaveSpacingListBox.Value=sprintf('%s',FDSMultiple.Oct_im);
            catch
            end   

            try 
                app.NumberofArraysListBox.Value=sprintf('%d',FDSMultiple.number);
            catch
            end    
            ChangeRows(app)

            try 
                app.UITable_TH.Data=FDSMultiple.ATH;
            catch
                warndlg('app.UITable_TH  failed');
            end 

            try 
                app.array_name=FDSMultiple.array_name;
            catch
            end    

            try
                writepname=FDSMultiple.writepname;
            catch
            end

            try
                for i=1:FDSMultiple.number
                    
                    fname=app.array_name{i};
                            
                    try
                        aa=evalin('base',fname);   
                    catch
                        load_mat(writepname,fname);
                    end
                          
                end    

                disp('pass')
            catch
                disp('fail')
            end    

            update_list(app);

            msgbox('load complete');
           
        end

        % Button down function: PSDEnvelopeviaFDSTab
        function PSDEnvelopeviaFDSTabButtonDown(app, event)
                      
        end

        % Value changed function: NumberofFDSArraysListBox
        function NumberofFDSArraysListBoxValueChanged(app, event)
            ChangeRows_FDS_PSD(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';       
            app.ExportOutputsButton.Visible='off';
        end

        % Button pushed function: CalculateButton_env
        function CalculateButton_envPushed(app, event)

            cleartext(app);

            app.MsgEditField.Value='a';

            ClosePlots(app);

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end


            fig_num=1;        

            key=app.IncludeSMCS016MinimumWorkmanshipCheckBox.Value;

            app.ntrials=str2num(app.NumberofTrialsEditField.Value);
            app.T_out=str2num(app.PSDDurationsecEditField.Value);

            ssc=str2num(app.MaxSlopedBoctEditField.Value);
            FDS_PSD.slopec=ssc;

            app.slopec=(ssc/10)/log10(2);

            FDS_PSD.ntrials=app.ntrials;
            FDS_PSD.T_out=app.T_out;
            
            Items = char(app.InputFDSTypeListBox.Items);
            im=app.InputFDSTypeListBox.Value;
            [app.nmetric]=find_index(Items,im);
            FDS_PSD.nmetric=im;

            Items = char(app.OutputPSDUnitListBox.Items);
            im=app.OutputPSDUnitListBox.Value;
            [app.iu]=find_index(Items,im);
            FDS_PSD.iu=im;

            Items = char(app.OptimizationGoalListBox.Items);
            im=app.OptimizationGoalListBox.Value;
            [app.goal]=find_index(Items,im);
            FDS_PSD.goal=im;

            Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
            im=app.PlateauMinimumOctaveSpacingListBox.Value;
            [app.ip]=find_index(Items,im);
            FDS_PSD.ip=im;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            im=app.NumberofFDSArraysListBox.Value;
            N=str2num(app.NumberofFDSArraysListBox.Value);
            FDS_PSD.N=im;

            app.Q=zeros(N,1);
            app.bex=zeros(N,1);
            
            app.AData=app.UITable_FDS_PSD.Data;
            FDS_PSD.AData=app.AData;

            A=table2cell(app.UITable_FDS_PSD.Data);

            clear app.Q;
            clear app.bex;
            
            for i=1:N
                app.FDS_name{i}=char(A{i,1});
            end

            for i=1:N
                try
                    app.Q(i)=str2num(strtrim(A{i,2}));
                catch
                end
            end

            for i=1:N
                try
                    app.bex(i)=str2num(strtrim(A{i,3}));
                catch
                end
            end
                
            for i=1:N
                data_s{i,1}=app.FDS_name{i};
                data_s{i,2}=sprintf('%g',app.Q(i));
                data_s{i,3}=sprintf('%g',app.bex(i));      
            end
            
            for i=1:N
            
                try
                    FS=app.FDS_name{i};
                    try
                        aq=evalin('base',FS);  
                    catch
                        try
                            FSS=sprintf('%s.txt',FS);
                            aq=readmatrix(FSS);
                            assignin('base',FS,aq)
                            aq=evalin('base',FS); 
                        catch
                            out1=sprintf('FDS array not found: %d %s',i,FS);
                            warndlg(out1);
                            return; 
                        end    
                    end

                    if(i==1)
                        app.fmin=aq(1,1);
                        app.fmax=aq(end,1);
                    end    

                catch
                    out1=sprintf('FDS array not found: %d %s',i,FS);
                    warndlg(out1);
                    return; 
                end
                
                if(isempty(aq))
                    warndlg('FDS array is empty');       
                    return;
                end
                
                if(i==1)
                    fn=aq(:,1);
                    n_ref=length(fn);
                    fds_ref=zeros(n_ref,N);
                end
                
                if(length(aq(:,2))~=n_ref)
                    warndlg('FDS length error');
                    return;
                end
                
                try
                   for k=1:n_ref
                        fds_ref(k,i)=aq(k,2);
                   end
                catch
                    warndlg('FDS array error');        
                    return;         
                end
                
            end               

            try
                f1=fn(1);
                f2=fn(end);
            catch
                warndlg('fn failed');
                return;
            end

            if(key==1)

                %
                % Interpolate SMC-S-016
                %
    
                spsd=[  20 	  0.0053 
                        150   0.04 
                        800   0.04 
                        2000  0.0064];                 
    
                [fi,sai] = interpolate_PSD_arbitrary_frequency(spsd(:,1),spsd(:,2),fn);

                nfc=2;

                FL=min(fn);
                FU=max(fn);

                f_ref=fi;

                nx=app.ntrials*0.3;
                            
                if(nx<4)
                    nx=4;
                end                

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.NumberofPSDCoordinatesListBox.Items);
            im=app.NumberofPSDCoordinatesListBox.Value;
            [app.npb]=find_index(Items,im);
            FDS_PSD.npb=im;
            
            if(app.npb==1)
                nbreak=2;
            end
            if(app.npb==2)
                nbreak=3;    
            end
            if(app.npb==3)
                nbreak=3;    
            end
            if(app.npb==4)
                nbreak=4;    
            end
            if(app.npb==5)
                nbreak=4;    
            end
            if(app.npb==6)
                nbreak=5;    
            end
            if(app.npb==7)
                nbreak=5;    
            end
            if(app.npb==8)
                nbreak=6;    
            end
            if(app.npb==9)
                nbreak=5;    
            end
            if(app.npb==10)
                nbreak=4;    
            end
            if(app.npb==11)
                nbreak=3;    
            end
            if(app.npb==12)
                nbreak=8;    
            end
            if(app.npb==13)
                nbreak=10;    
            end
            if(app.npb==14)
                nbreak=length(fn);
            end
               
            app.npb
            nbreak

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            damp=zeros(N,1);
            
            for i=1:N
                damp(i)=1/(2*app.Q(i));
                
                if(damp(i)>=1.0e-10 && damp(i)<=0.5)
                else
                    warndlg('Q value error');
                    fprintf(' i=%d damp=%8.4g  Q=%8.4g \n',i,damp(i),app.Q(i));
                    return;
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            record = 1.0e+90;
            grmslow=1.0e+50;
            vrmslow=1.0e+50;
            drmslow=1.0e+50;
            jrmslow=1.0e+50;
            %
            xf=zeros(nbreak,1);
            xapsd=zeros(nbreak,1);
            %         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
            initial=2;
            final=2;
            
            disp(' ');
            disp(' Generate sample PSDs');
            disp(' ');
            %
            progressbar;
            
            NLL=round(app.ntrials/2);
            if(NLL<10)
                NLL=10;
            end
            
            if(app.ip==1)
                plateau=1;
            end
            if(app.ip==2)
                plateau=2/3;
            end
            if(app.ip==3)
                plateau=1/2; 
            end
            if(app.ip==4)
                plateau=1/3; 
            end
            if(app.ip==5)
                plateau=1/4; 
            end

            if(app.npb==14)
                plateau=0;
            end    
            
            for ik=1:app.ntrials
                
                progressbar(ik/app.ntrials);
           
                if(key==0)
           
                    if(rand()>0.5 || ik<NLL)
                %	   
			                % Generate the sample psd
                            [f_sam,apsd_sam,max_sss,app.slopec]=...
                                env_generate_sample_psd_alt(n_ref,nbreak,app.npb,fn,ik,app.slopec,initial,final,f1,f2,plateau);            
                %
                    else
                %
			                [f_sam,apsd_sam,max_sss,app.slopec]=...
                                env_generate_sample_psd2_alt(n_ref,nbreak,app.npb,fn,xf,xapsd,app.slopec,initial,final,ik,f1,f2,plateau);
                %
                    end
    
                %
                %      Interpolate the sample psd
                
                    if(app.npb<=13)
                        [fn,apsd_samfine]=env_interp_sam(f_sam,apsd_sam,nbreak,n_ref,fn);
                    else
                        fn=f_sam;
                        apsd_samfine=apsd_sam;
                    end
                % 
                    a11=max(apsd_samfine);
                    a22=min(apsd_samfine);
                    
                    if(a22<1.0e-20)
                        fprintf('\n max(apsd_samfine)=%8.4g  min(apsd_samfine)=%8.4g \n\n',a11,a22);
                        return;
                    end
                
                %      Calculate the fds of the sample psd
       
                    [fds_samfine]=env_fds_batch_alt_apvtp(apsd_samfine,n_ref,fn,damp,app.bex,app.T_out,app.iu,app.nmetric);
                    
                %      Compare the sample fds with the reference fds
                    [scale]=env_compare_alt(n_ref,fds_ref,fds_samfine,app.bex);
                %
                %      scale the psd
                    scale=(scale^2.);
                    apsd_sam=apsd_sam*scale;

                else   % include SMC-S-016

                    f_ref=fn;

                    if(rand()>0.7 || ik<nx)

                    % Generate the sample psd
                                    
                        [f_sam,apsd_sam]=...
                        env_generate_sample_psd_smc(n_ref,nbreak,f_ref,initial,final,f1,f2,FL,FU,nfc);
                    %          
                    else
                    %         
                        [f_sam,apsd_sam]=env_generate_sample_psd2_via_vrs_alt(xf,xapsd);                 
                    end
                        
                    %
                    %     Interpolate the sample psd
                    [f_samfine,apsd_samfine]=env_interp_sam(f_sam,apsd_sam,nbreak,n_ref,f_ref);
            
                    %     envelope SMC-S-016
            
                    for i=1:n_ref
                        if(apsd_samfine(i)<sai(i))
                            apsd_samfine(i)=sai(i);
                        end    
                    end   
            
                %      Calculate the fds of the sample psd
       
                    [fds_samfine]=env_fds_batch_alt_apvtp(apsd_samfine,n_ref,fn,damp,app.bex,app.T_out,app.iu,app.nmetric);
                    
                %      Compare the sample fds with the reference fds
                    [scale]=env_compare_alt(n_ref,fds_ref,fds_samfine,app.bex);

                    %
                    %      scale the psd
                    scale=(scale^2);
                    apsd_samfine=apsd_samfine*scale*1.04;
            
                    %     envelope SMC-S-016
            
                    for i=1:n_ref
                        if(apsd_samfine(i)<sai(i))
                            apsd_samfine(i)=sai(i);
                        end    
                    end   
            
                    %
                    %      Smooth the PSD
                    %
                    [f_sam,apsd_sam]=smooth_psd_function(f_samfine,apsd_samfine);
                    f_sam(1)=fn(1);

                    nbreak=length(f_sam);

                end    

            %       
            %      calculate the grms value 
            %             
                [grms,jrms]=env_grms_sam2(nbreak,f_sam,apsd_sam);
                [vrms]=env_vrms_sam(nbreak,f_sam,apsd_sam);
                [drms]=env_drms_sam(nbreak,f_sam,apsd_sam);
            %
                [app.iflag,record]=env_checklow3(jrms,grms,vrms,drms,jrmslow,grmslow,vrmslow,drmslow,record,app.goal); 
                [max_sss,min_fff]=freq_slope_check(f_sam,apsd_sam);

                ijk=0;

                if(key==0)
                    if(app.iflag==1 && ((app.npb<=13 && max_sss<app.slopec && min_fff>plateau) || app.npb==14)) 
                        ijk=1;
                    end    
                else
                    if(app.iflag==1) 
                        ijk=1;
                    end                        
                end    


                if(ijk==1)      
            %
                    if(drms<drmslow)
                        drmslow=drms;
                    end
		            if(vrms<vrmslow)
			            vrmslow=vrms;
                    end
		            if(grms<grmslow)
			            grmslow=grms;
                    end
		            if(jrms<jrmslow)
			            jrmslow=jrms;
                    end        
            %
                    f_sam=fix_size(f_sam);
                    apsd_sam=fix_size(apsd_sam);
            
                    xf=f_sam;
 		            xapsd=apsd_sam;
                    
                    app.power_spectral_density=[f_sam(1:nbreak) apsd_sam(1:nbreak)];
                    
                    drms_rec=drms;
                    vrms_rec=vrms;
                    grms_rec=grms;
             
                    fprintf('\n Trial %ld, PSD Coordinates \n\n',ik);
                    
                    if(app.npb<=13)
                    
                        disp('  Freq(Hz)  Accel(G^2/Hz) '); 
                        for i=1:nbreak
                            fprintf(' %8.2f \t %8.4g \n',f_sam(i),apsd_sam(i));
                        end
                    
                        disp(' ');     
                    end
            %
                    fprintf('   Trial: drms=%10.4g  vrms=%10.4g  grms=%10.4g \n',drms,vrms,grms); 
                    fprintf('  Record: drms=%10.4g  vrms=%10.4g  grms=%10.4g \n\n',drms_rec,vrms_rec,grms_rec); 

                end
            
            end

            app.MsgEditField.Value='b';
            
            xf=round(xf);
            
            pause(0.3);
            progressbar(1);
            
            %       
            % Interpolate the best psd
            %
 
            [xapsdfine]=env_interp_best(nbreak,n_ref,xf,xapsd,fn);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            xapsdfine=fix_size(xapsdfine);
            
            %
            % Calculate the fds of the best psd
            %
            n_ref=length(xapsdfine);
            
            [xfds]=env_fds_batch_alt_apvtp(xapsdfine,n_ref,fn,damp,app.bex,app.T_out,app.iu,app.nmetric);
   
            %
               grms=grms_rec;
               vrms=vrms_rec;
               drms=drms_rec;

            app.MsgEditField.Value='c';

            %
            disp('_____________________________________________________________________');
            %
            disp('Optimum Case');
            disp(' ');
            
            ss=sprintf('Optimum Case ');

            if(app.iu==1)
                fprintf(' Freq(Hz) \t Accel(G^2/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Accel(G^2/Hz)  \n');
            end
            if(app.iu==2)
                fprintf(' Freq(Hz) \t Accel((m/sec^2)^2)/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Accel((m/sec^2)^2)/Hz  \n');
            end
            if(app.iu==3)
                fprintf(' Freq(Hz) \t Pressure(psi^2/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Pressure(psi^2/Hz)  \n');
            end

            ss=sprintf('%s\n\n%s',ss,ss2);

            nbreak=length(app.power_spectral_density(:,1));
            
            for i=1:nbreak
                out1=sprintf(' %6.1f \t%8.4g  ',app.power_spectral_density(i,1),app.power_spectral_density(i,2));
                disp(out1);

                ss=sprintf('%s\n%s',ss,out1);
            end

            [max_sss,min_fff]=freq_slope_check(app.power_spectral_density(:,1),app.power_spectral_density(:,2));
                
            fprintf('\n max slope=%8.4g  min freq sep=%8.4g\n\n',max_sss,min_fff);
            
            %
            fprintf(' drms=%10.4g  vrms=%10.4g  grms=%10.4g \n',drms,vrms,grms);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            fn=fn(1:n_ref);
            xfds=xfds(1:n_ref,:);
            fds_ref=fds_ref(1:n_ref,:);
            
            for i=1:length(damp)    
            %
                xx=zeros(n_ref,1);
                ff=zeros(n_ref,1);
            %        
                for k=1:n_ref
                    xx(k)=xfds(k,i);
                    ff(k)=fds_ref(k,i);
                end
            %
                xx=fix_size(xx);
                ff=fix_size(ff);
                fn=fix_size(fn);
            %
                fds1=[fn xx];
                fds2=[fn ff];
            %
            end    

            if(app.nmetric<=3)
                ylab='Accel (G^2/Hz)';
                t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g GRMS ',grms);
                s4=sprintf(' Overall Level = %6.3g GRMS ',grms);
            else 
                if(app.iu==3)
                    ylab='Pressure (psi^2/Hz)';   
                    t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g psi rms ',grms);
                    s4=sprintf(' Overall Level = %6.3g psi rms ',grms);
                else
                    ylab='Pressure (Pa^2/Hz)';   
                    t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g Pa rms ',grms);
                    s4=sprintf(' Overall Level = %6.3g Pa rms ',grms);
                end    
            end  

             app.MsgEditField.Value='0';

            try
                 app.MsgEditField.Value='1';
            
                x_label=sprintf(' Frequency (Hz)');
                
                [fig_num,h2]=plot_PSD_function(fig_num,x_label,ylab,t_string,app.power_spectral_density,app.fmin,app.fmax);
                   
                %
                app.MsgEditField.Value='2';
                
                app.MsgEditField.Value='3';

            catch
                app.MsgEditField.Value='psd plot failed'           
                % warndlg('psd plot failed')
                return;
            end

            app.MsgEditField.Value='5';

            try
                    app.MsgEditField.Value='6';

                    %
                    x_label=sprintf(' Natural Frequency (Hz)');
                    ylab='Damage Index';
                    %
                    disp(' ');
                    disp(' FDS output arrays: ');
                    disp('   ');
                    
                    uiu=(length(unique(damp))+length(unique(app.bex))); 

                    fn=fn(1:n_ref);
                    xfds=xfds(1:n_ref,:);
                    fds_ref=fds_ref(1:n_ref,:);
                    
                    for i=1:length(damp)            
                    %
                            xx=zeros(n_ref,1);
                            ff=zeros(n_ref,1);
                    %        
                            for k=1:n_ref
                                xx(k)=xfds(k,i);
                                ff(k)=fds_ref(k,i);
                            end
                    %
                            xx=fix_size(xx);
                            ff=fix_size(ff);
                            fn=fix_size(fn);
                    %
                            fds1=[fn xx];
                            fds2=[fn ff];
                    %
                    %%%%%%%%%%%
                    %
                            sbex=sprintf('%g',app.bex(i));
                            sbex=strrep(sbex, '.', 'p');
                            output_name=sprintf('psd_fds_Q%g_b%s',app.Q(i),sbex);
                            output_name2=sprintf('    %s',output_name);
                            disp(output_name2);
                            assignin('base', output_name, fds1);
                    %
                    %%%%%%%%%%%
                    %       
                            output_name_ref=sprintf('time_history_fds_Q%g_b%s',app.Q(i),sbex);
                            assignin('base', output_name_ref, fds2);
                    
                            fprintf('    %s\n',output_name_ref);        
                    %
                    %%%%%%%%%%%
                    %
                            if(uiu~=4)
                                leg_a=sprintf('PSD Envelope');
                                leg_b=sprintf('Measured Data');
                    %
                                [fig_num,h3]=...
                                plot_fds_two_h2(fig_num,x_label,ylab,fds1,fds2,leg_a,leg_b,app.Q(i),app.bex(i),app.iu,app.nmetric);    
                            
                            end
                    %                        
                    end    
   
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %
                    
                    if(uiu==4)
                       
                        [fig_num,h3]=fds_plot_2x2_alt_h2(fig_num,app.Q,app.bex,fn,ff,xx,xfds,fds_ref,app.nmetric,app.iu);
                
                        [fig_num,h4]=psd_fds_plot_2x2_alt_h2(fig_num,app.Q,app.bex,fn,xfds,...
                                                             fds_ref,app.nmetric,app.iu,t_string,...
                                                             app.power_spectral_density,app.fmin,app.fmax);    
                    end

            catch
                warndlg('uiu 4 failed');
                return;
            end

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            app.MsgEditField.Value='7';

            try
            
                disp(' ');
                disp(' The fatigue damage spectra is calculated from the amplitude (peak-valley)/2 ');
                            
                output_name=strtrim(app.OptimizedPSDOuputNameInternalExternalEditField.Value);
                
                if(isempty(output_name))
                    warndlg('Enter output PSD name');
                    return;
                end 
    
                FDS_PSD.output_name=output_name;

                ss=sprintf('%s\n\n Duration = %g sec \n\n PSD output name: \n %s \n',ss,app.T_out,output_name);
            
            catch
                warndlg('ref 10:  failed ');
                return;
            end

            app.MsgEditField.Value='8';

            try
                    ss=sprintf('%s\n FDS of PSD Envelope \n',ss);
        
                    for i=1:length(damp)    
                    %
                            xx=zeros(n_ref,1);
                            ff=zeros(n_ref,1);
                    %        
                            for k=1:n_ref
                                xx(k)=xfds(k,i);
                                ff(k)=fds_ref(k,i);
                            end
                    %
                            xx=fix_size(xx);
                            ff=fix_size(ff);
                            fn=fix_size(fn);
                    %
                            fds1=[fn xx];
                            fds2=[fn ff];
                    %
                    %%%%%%%%%%%
                    %
                            sbex=sprintf('%g',app.bex(i));
                            sbex=strrep(sbex, '.', 'p');
                            output_name=sprintf('psd_fds_Q%g_b%s',app.Q(i),sbex);
                            output_name2=sprintf('    %s',output_name);
                            disp(output_name2);
                            assignin('base', output_name, fds1);
                            ss=sprintf('%s\n%s',ss,output_name2);
                    end

            catch
                warndlg('fds assignin failed');
                return;
            end
            
            app.MsgEditField.Value='9';

            try
                app.ResultsTextArea.Value=ss;
                app.ResultsTextArea.Visible='on';
                app.ResultsTextAreaLabel.Visible='on';
                app.ExportOutputsButton.Visible='on';
            catch
                warndlg('Text Area failed');
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            try
                app.big=FDS_PSD;
            catch
                warndlg('app.big failed');
            end

            sx=strtrim(app.OptimizedPSDOuputNameInternalExternalEditField.Value);

            disp(' ');
            disp(' Optimized PSD output name: ');
            fprintf('  %s \n',sx);
            try
                assignin('base',sx,app.power_spectral_density);
                ss=sprintf('Output PSD array: %s',sx);
                msgbox(ss)
            catch
                warndlg('PSD save failed');
            end            

            % ChangeRows(app);

        end

        % Value changed function: InputFDSTypeListBox
        function InputFDSTypeListBoxValueChanged(app, event)
            cleartext(app); 
            eFDS(app);
        end

        % Value changed function: OutputPSDUnitListBox
        function OutputPSDUnitListBoxValueChanged(app, event)
            cleartext(app);
        end

        % Value changed function: NumberofPSDCoordinatesListBox
        function NumberofPSDCoordinatesListBoxValueChanged(app, event)
            cleartext(app);
        end

        % Value changed function: OptimizationGoalListBox
        function OptimizationGoalListBoxValueChanged(app, event)
            cleartext(app); 
        end

        % Value changed function: PlateauMinimumOctaveSpacingListBox
        function PlateauMinimumOctaveSpacingListBoxValueChanged(app, event)
            cleartext(app);
        end

        % Value changing function: PSDDurationsecEditField
        function PSDDurationsecEditFieldValueChanging(app, event)
            cleartext(app); 
        end

        % Value changing function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanging(app, event)
            cleartext(app);
        end

        % Value changing function: MaxSlopedBoctEditField
        function MaxSlopedBoctEditFieldValueChanging(app, event)
            cleartext(app);
        end

        % Cell edit callback: UITable_FDS_PSD
        function UITable_FDS_PSDCellEdit(app, event)
            cleartext(app);
        end

        % Selection changed function: UITable_FDS_PSD
        function UITable_FDS_PSDSelectionChanged(app, event)
            cleartext(app); 
        end

        % Cell selection callback: UITable_FDS_PSD
        function UITable_FDS_PSDCellSelection(app, event)
            indices = event.Indices
            
        end

        % Display data changed function: UITable_FDS_PSD
        function UITable_FDS_PSDDisplayDataChanged(app, event)
            newDisplayData = app.UITable_FDS_PSD.DisplayData;
            
        end

        % Button pushed function: ExtractQbfromArrayNameButton
        function ExtractQbfromArrayNameButtonPushed(app, event)
          
            extractbQ(app);

        end

        % Button pushed function: LoadModelButton_env
        function LoadModelButton_envPushed(app, event)
        
            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

            catch
            end
            
            try
                ss=sprintf('%s\\*.mat',writepname);
                ff = uigetfile(ss, 'Select load model file');
                NAME=char(fullfile(writepname,ff));
            catch
            end    
            
            try

                try
                    struct=load(NAME);
                    structnames = fieldnames(struct, '-full'); % fields in the struct        
                    k=length(structnames);
                catch
                    NAME
                    disp('NAME fail');
                end
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end

            catch
                warndlg('load failed')
                return;
            end
            
            % struct
            
            try
               FDS_PSD=evalin('base','FDS_PSD');
            catch
               warndlg(' evalin failed 2');
               return;
            end
            
            %%%%%%%%%%%%

            try
                app.NumberofFDSArraysListBox.Value=sprintf('%s',FDS_PSD.N);
            catch
            end

            try
                app.OutputPSDUnitListBox.Value=sprintf('%s',FDS_PSD.iu);
            catch         
            end

            try
                app.OptimizationGoalListBox.Value=sprintf('%s',FDS_PSD.goal);
            catch  
            end

            try
                app.PlateauMinimumOctaveSpacingListBox.Value=sprintf('%s',FDS_PSD.ip);
            catch  
            end

            try
                app.NumberofPSDCoordinatesListBox.Value=sprintf('%s',FDS_PSD.npb);
            catch  
            end

            %%%%%%%%%%%%

            try 
                app.NumberofTrialsEditField.Value=sprintf('%d',FDS_PSD.ntrials);
            catch
            end 

            try 
                app.PSDDurationsecEditField.Value=sprintf('%g',FDS_PSD.T_out);
            catch 
            end 

            try 
                app.MaxSlopedBoctEditField.Value=sprintf('%g',FDS_PSD.slopec);
            catch
            end   

            try
                ChangeRows_FDS_PSD(app);
            catch   
            end

            try
                app.UITable_FDS_PSD.Data=FDS_PSD.AData;
            catch  
            end

            try
                nnn=char(FDS_PSD.name)

                sz=size(nnn)

                try
    
                    for i=1:sz(1)
                        
                        fname=nnn(i,:)
                                
                        try
                            aa=evalin('base',fname);   
                        catch
                            try
                                load_mat(writepname,fname);
                            catch    
                                disp('load failed')
                            end    
                        end
                              
                    end

                catch
                    disp('loop failed')
                end

                disp('pass')
            catch
                disp('fail')
            end    

            update_list(app);

            msgbox('Load Complete')

        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            
            try 
                FDSMultiple.fmin=app.fmin;
            catch
            end 
            try 
                FDSMultiple.fmax=app.fmax;
            catch
            end  

            try 
                FDSMultiple.start_time=app.start_time;
            catch
            end 
            try 
                FDSMultiple.end_time=app.end_time;
            catch
            end  

            try 
                FDSMultiple.nQ=app.nQ;
            catch
            end  
            try 
                FDSMultiple.nb=app.nb;
            catch
            end  
            try 
                FDSMultiple.Q=unique(app.Q);
            catch
            end  
            try 
                FDSMultiple.bex=unique(app.bex);
            catch
            end              
            try 
                FDSMultiple.number=app.number;
            catch
            end    
            try 
                FDSMultiple.Oct_im=app.Oct_im;
            catch
            end                          
            try 
                FDSMultiple.ATH=app.ATH;
            catch
            end 

            try 
                FDSMultiple.array_name=app.array_name;
            catch
            end   

            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end   

            catch
            end

            try
                app.array_name
                sz=size(app.array_name)

                for i=1:max(sz)
                    FS=app.array_name{1,i};
                    writefname=sprintf('%s.mat',FS);

                    try
                        save_mat(writefname, writepname,FS)
                    catch
                    end
                end
               
            catch
                disp('fail')
            end
            
            try 
                structnames = fieldnames(FDSMultiple, '-full'); % fields in the struct

                FDSMultiple.writepname=writepname;

                ns= inputdlg({'Model Save Filename'});

                elk=char(fullfile(writepname, ns));

                save(elk, 'FDSMultiple');
          
            catch
                warndlg('Save error');
                return;
            end
        end

        % Button pushed function: ExportFDSOutputButton
        function ExportFDSOutputButtonPushed(app, event)

                try
                    writepname = app.FolderEditField.Value;
                    sz=size(app.name);

                    if(sz(1)==0)
                        warndlg('Calculate FDS first');
                        return;
                    end    

                    for i=1:sz(1)
                        for j=1:sz(2)
                            n1=app.name{i,j};
                            ddd=evalin('base',n1);
                            n1 = fullfile(writepname, n1);
                            writematrix(ddd,n1,'Delimiter','\t');
                        end
                    end   
                    msgbox('Export complete');
                catch
                    warndlg('Export failed');
                end    

        end

        % Button pushed function: SetSaveExportFolderButton
        function SetSaveExportFolderButtonPushed(app, event)
            selected_dir = uigetdir();
            app.FolderEditField.Value=selected_dir; 
        end

        % Button pushed function: ExportOutputsButton
        function ExportOutputsButtonPushed(app, event)
            try
                writepname = app.FolderEditField.Value;
                
                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

                ddd=app.power_spectral_density;

                sz=size(ddd);

                for i=1:sz(1)
                    ddd(i,1)=str2num(sprintf('%5.1f',ddd(i,1)));
                    ddd(i,2)=str2num(sprintf('%7.3g',ddd(i,2)));                  
                end    


                ns= app.OptimizedPSDOuputNameInternalExternalEditField.Value;
                n1 = fullfile(writepname, ns);
                writematrix(ddd,n1,'Delimiter','\t');
                msgbox('Export complete');
            catch
                warndlg('Export failed');
            end    
        end

        % Button pushed function: SaveModelButton_env
        function SaveModelButton_envPushed(app, event)
            
            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

            catch
            end

            try
                app.AData=app.UITable_FDS_PSD.Data;
                FDS_PSD.AData=app.AData;
            catch
            end

            try
                im=app.NumberofFDSArraysListBox.Value;
                N=str2num(app.NumberofFDSArraysListBox.Value);
                FDS_PSD.N=im;
            catch
            end

            try
        
                A=table2cell(app.AData);
            
                for i=1:FDS_PSD.N
                    app.FDS_name{i}=char(A{i,1});
                end
            catch
            end

            try
                Items = char(app.NumberofPSDCoordinatesListBox.Items);
                im=app.NumberofPSDCoordinatesListBox.Value;
                [app.npb]=find_index(Items,im);
                FDS_PSD.npb=im;         
            catch
            end

            try
                Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
                im=app.PlateauMinimumOctaveSpacingListBox.Value;
                [app.ip]=find_index(Items,im);
                FDS_PSD.ip=im;
            catch
            end
            try
                ssc=str2num(app.MaxSlopedBoctEditField.Value);
                FDS_PSD.slopec=ssc;
            catch
            end

            try
                app.ntrials=str2num(app.NumberofTrialsEditField.Value);
                FDS_PSD.ntrials=app.ntrials;            
            catch
            end

            try
                app.T_out=str2num(app.PSDDurationsecEditField.Value);
                FDS_PSD.T_out=app.T_out;            
            catch
            end    

            try
                Items = char(app.InputFDSTypeListBox.Items);
                im=app.InputFDSTypeListBox.Value;
                [app.nmetric]=find_index(Items,im);
                FDS_PSD.nmetric=im;            
            catch
            end  

            try
                Items = char(app.OutputPSDUnitListBox.Items);
                im=app.OutputPSDUnitListBox.Value;
                [app.iu]=find_index(Items,im);
                FDS_PSD.iu=im;            
            catch
            end  

            try
                Items = char(app.OptimizationGoalListBox.Items);
                im=app.OptimizationGoalListBox.Value;
                [app.goal]=find_index(Items,im);
                FDS_PSD.goal=im;            
            catch
            end

            try
                FDS_PSD.name=app.FDS_name;
            catch
            end

            try
                sz=size(FDS_PSD.name);

                for i=1:sz(1)
                    FS=FDS_PSD.name{i};
                    writefname=sprintf('%s.mat',FS);

                    try
                        save_mat(writefname, writepname,FS)
                    catch
                    end
                end               
            catch
            end

               
            try 
                ns= inputdlg({'Model Save Filename'});
            catch
            end    

            try
                structnames = fieldnames(FDS_PSD, '-full'); % fields in the struct
            catch
            end
            try
                elk=char(fullfile(writepname, ns));
                save(elk, 'FDS_PSD');
            catch
                warndlg('Save error');
                return;
            end

            msgbox('Save complete')
        end

        % Value changed function: OutputFDSTypeListBox
        function OutputFDSTypeListBoxValueChanged(app, event)
            value = app.OutputFDSTypeListBox.Value;
            eFDS(app);
        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)
            eFDS(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.ImportTimeHistoryArraysTab = uitab(app.TabGroup);
            app.ImportTimeHistoryArraysTab.Title = 'Import Time History Arrays';
            app.EachinputarraymusthavetwocolumnstimesecaccelorpressureLabel = uilabel(app.ImportTimeHistoryArraysTab);
            app.EachinputarraymusthavetwocolumnstimesecaccelorpressureLabel.Text = 'Each input array must have two columns:  time (sec) & acceleration or pressure';
            app.ReadNewFileButton = uibutton(app.ImportTimeHistoryArraysTab, 'push');
            app.ReadNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNewFileButtonPushed, true);
            app.ReadNewFileButton.Text = 'Read New File';
            app.Label_2 = uilabel(app.ImportTimeHistoryArraysTab);
            app.Label_2.Text = {'A = readmatrix(filename) creates an array by reading column-oriented '; 'data from a file. The readmatrix function performs automatic detection '; 'of import parameters for your file.'; ''; 'readmatrix determines the file format from the file extension:'; ''; '.txt, .dat, or .csv for delimited text files'; ''; '.xls, .xlsb, .xlsm, .xlsx, .xltm, .xltx, or .ods for spreadsheet files'; ''; 'For files containing mixed numeric and text data, readmatrix imports '; 'the data as a numeric array by default'};
            app.CurrentWorkspaceListBoxLabel = uilabel(app.ImportTimeHistoryArraysTab);
            app.CurrentWorkspaceListBoxLabel.Text = 'Current Workspace';
            app.CurrentWorkspaceListBox = uilistbox(app.ImportTimeHistoryArraysTab);
            app.CurrentWorkspaceListBox.Items = {};
            app.CurrentWorkspaceListBox.Value = {};
            app.UpdateListButton = uibutton(app.ImportTimeHistoryArraysTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.FDSTab = uitab(app.TabGroup);
            app.FDSTab.Title = 'FDS';
            app.MinFreqHzEditFieldLabel = uilabel(app.FDSTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.FDSTab, 'text');
            app.MinFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MinFreqHzEditFieldValueChanging, true);
            app.MinFreqHzEditField.Value = '10';
            app.MaxFreqHzEditFieldLabel = uilabel(app.FDSTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.FDSTab, 'text');
            app.MaxFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MaxFreqHzEditFieldValueChanging, true);
            app.MaxFreqHzEditField.Value = '2000';
            app.OctaveSpacingListBoxLabel = uilabel(app.FDSTab);
            app.OctaveSpacingListBoxLabel.Text = 'Octave Spacing';
            app.OctaveSpacingListBox = uilistbox(app.FDSTab);
            app.OctaveSpacingListBox.Items = {'1/3', '1/6', '1/12', '1/24', '1/48'};
            app.OctaveSpacingListBox.ValueChangedFcn = createCallbackFcn(app, @OctaveSpacingListBoxValueChanged, true);
            app.OctaveSpacingListBox.Value = '1/12';
            app.NumberofQCasesListBoxLabel = uilabel(app.FDSTab);
            app.NumberofQCasesListBoxLabel.Text = 'Number of Q Cases';
            app.NumberofQCasesListBox = uilistbox(app.FDSTab);
            app.NumberofQCasesListBox.Items = {'1', '2'};
            app.NumberofQCasesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofQCasesListBoxValueChanged, true);
            app.NumberofQCasesListBox.Value = '1';
            app.NumberofbCasesListBoxLabel = uilabel(app.FDSTab);
            app.NumberofbCasesListBoxLabel.Text = 'Number of b Cases';
            app.NumberofbCasesListBox = uilistbox(app.FDSTab);
            app.NumberofbCasesListBox.Items = {'1', '2'};
            app.NumberofbCasesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofbCasesListBoxValueChanged, true);
            app.NumberofbCasesListBox.Value = '1';
            app.UITable_Q = uitable(app.FDSTab);
            app.UITable_Q.ColumnName = {'Q'};
            app.UITable_Q.RowName = {};
            app.UITable_Q.ColumnEditable = [true false false false];
            app.UITable_b = uitable(app.FDSTab);
            app.UITable_b.ColumnName = {'b'};
            app.UITable_b.RowName = {};
            app.UITable_b.ColumnEditable = [true false false false];
            app.CalculateButton = uibutton(app.FDSTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LoadModelButton = uibutton(app.FDSTab, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.UITable_TH = uitable(app.FDSTab);
            app.UITable_TH.ColumnName = {'Time History Array Name'; 'Select'};
            app.UITable_TH.RowName = {};
            app.UITable_TH.ColumnEditable = [false true];
            app.UpdateTableButton = uibutton(app.FDSTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.StartTimesecEditFieldLabel = uilabel(app.FDSTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.FDSTab, 'text');
            app.StartTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @StartTimesecEditFieldValueChanging, true);
            app.StartTimesecEditField.Value = '0';
            app.EndTimesecEditFieldLabel = uilabel(app.FDSTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.FDSTab, 'text');
            app.EndTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @EndTimesecEditFieldValueChanging, true);
            app.EndTimesecEditField.Value = '1000';
            app.SelectoneormorearraynamesLabel = uilabel(app.FDSTab);
            app.SelectoneormorearraynamesLabel.Text = 'Select one or more array names';
            app.OutputArrayNamesTextAreaLabel = uilabel(app.FDSTab);
            app.OutputArrayNamesTextAreaLabel.Text = 'Output Array Names';
            app.OutputArrayNamesTextArea = uitextarea(app.FDSTab);
            app.OutputArrayNamesTextArea.Editable = 'off';
            app.SaveModelButton = uibutton(app.FDSTab, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.ExportFDSOutputButton = uibutton(app.FDSTab, 'push');
            app.ExportFDSOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFDSOutputButtonPushed, true);
            app.ExportFDSOutputButton.Text = 'Export FDS Output';
            app.OutputFDSTypeListBoxLabel = uilabel(app.FDSTab);
            app.OutputFDSTypeListBoxLabel.Text = 'Output FDS Type';
            app.OutputFDSTypeListBox = uilistbox(app.FDSTab);
            app.OutputFDSTypeListBox.Items = {'Absolute Acceleration', 'Pseudo Velocity', 'Relative Displacement', 'Transmitted Pressure'};
            app.OutputFDSTypeListBox.ValueChangedFcn = createCallbackFcn(app, @OutputFDSTypeListBoxValueChanged, true);
            app.OutputFDSTypeListBox.Value = 'Absolute Acceleration';
            app.SelectUnitsListBoxLabel = uilabel(app.FDSTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.FDSTab);
            app.SelectUnitsListBox.Items = {'G, in/sec, in, psi', 'G, m/sec, m, Pa'};
            app.SelectUnitsListBox.Value = 'G, in/sec, in, psi';
            app.TimeHistoryDurationScaleFactorLabel = uilabel(app.FDSTab);
            app.TimeHistoryDurationScaleFactorLabel.Text = {'Time History Duration '; 'Scale Factor'};
            app.TimeHistoryDurationScaleFactorEditField = uieditfield(app.FDSTab, 'text');
            app.TimeHistoryDurationScaleFactorEditField.Value = '1';
            app.PSDEnvelopeviaFDSTab = uitab(app.TabGroup);
            app.PSDEnvelopeviaFDSTab.Title = 'PSD Envelope via FDS';
            app.PSDEnvelopeviaFDSTab.ButtonDownFcn = createCallbackFcn(app, @PSDEnvelopeviaFDSTabButtonDown, true);
            app.CalculateButton_env = uibutton(app.PSDEnvelopeviaFDSTab, 'push');
            app.CalculateButton_env.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_envPushed, true);
            app.CalculateButton_env.Text = 'Calculate';
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel_2 = uilabel(app.PSDEnvelopeviaFDSTab);
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel_2.Text = 'Double click on Array Name to access pull down menu.';
            app.LoadModelButton_env = uibutton(app.PSDEnvelopeviaFDSTab, 'push');
            app.LoadModelButton_env.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButton_envPushed, true);
            app.LoadModelButton_env.Text = 'Load Model';
            app.NumberofFDSArraysLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.NumberofFDSArraysLabel.Text = {'Number of '; 'FDS Arrays'};
            app.NumberofFDSArraysListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.NumberofFDSArraysListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofFDSArraysListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofFDSArraysListBoxValueChanged, true);
            app.NumberofFDSArraysListBox.Value = '1';
            app.PSDDurationsecEditFieldLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.PSDDurationsecEditFieldLabel.Text = 'PSD Duration (sec)';
            app.PSDDurationsecEditField = uieditfield(app.PSDEnvelopeviaFDSTab, 'text');
            app.PSDDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @PSDDurationsecEditFieldValueChanging, true);
            app.PSDDurationsecEditField.Value = '60';
            app.NumberofTrialsEditFieldLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.PSDEnvelopeviaFDSTab, 'text');
            app.NumberofTrialsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanging, true);
            app.NumberofTrialsEditField.Value = '2000';
            app.MaxSlopedBoctEditFieldLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.MaxSlopedBoctEditFieldLabel.Text = 'Max Slope (dB/oct)';
            app.MaxSlopedBoctEditField = uieditfield(app.PSDEnvelopeviaFDSTab, 'text');
            app.MaxSlopedBoctEditField.ValueChangingFcn = createCallbackFcn(app, @MaxSlopedBoctEditFieldValueChanging, true);
            app.MaxSlopedBoctEditField.Value = '9';
            app.OutputPSDUnitListBoxLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.OutputPSDUnitListBoxLabel.Text = 'Output PSD Unit';
            app.OutputPSDUnitListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.OutputPSDUnitListBox.Items = {'G^2/Hz', '(m/sec^2)^2/Hz', '(psi^2)/Hz'};
            app.OutputPSDUnitListBox.ValueChangedFcn = createCallbackFcn(app, @OutputPSDUnitListBoxValueChanged, true);
            app.OutputPSDUnitListBox.Value = 'G^2/Hz';
            app.NumberofPSDCoordinatesListBoxLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.NumberofPSDCoordinatesListBoxLabel.Text = 'Number of PSD Coordinates';
            app.NumberofPSDCoordinatesListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.NumberofPSDCoordinatesListBox.Items = {'2 arbitrary', '3 arbitrary', '3 ramp-plateau', '4 arbitrary', '4 ramp-plateau-ramp', '5 arbitrary', '5 ramp-plateau-ramp-plateau', '6 ramp-plateau-ramp-plateau-ramp', '5 ramp-ramp-plateau-ramp', '4 ramp-ramp-plateau', '2 plateau-ramp', '8 four ramps & three plateaus ', '10 five ramps & four plateaus ', 'arbitrary with same frequencies as input FDS'};
            app.NumberofPSDCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPSDCoordinatesListBoxValueChanged, true);
            app.NumberofPSDCoordinatesListBox.Value = '2 arbitrary';
            app.InputFDSTypeListBoxLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.InputFDSTypeListBoxLabel.Text = 'Input FDS Type';
            app.InputFDSTypeListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.InputFDSTypeListBox.Items = {'Absolute Acceleration', 'Pseudo Velocity', 'Relative Displacement', 'Transmitted Pressure'};
            app.InputFDSTypeListBox.ValueChangedFcn = createCallbackFcn(app, @InputFDSTypeListBoxValueChanged, true);
            app.InputFDSTypeListBox.Value = 'Absolute Acceleration';
            app.OptimizationGoalListBoxLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.OptimizationGoalListBoxLabel.Text = 'Optimization Goal';
            app.OptimizationGoalListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.OptimizationGoalListBox.Items = {'acceleration', 'acceleration & velocity', 'acceleration, velocity, displacement', 'displacement', 'velocity & displacement'};
            app.OptimizationGoalListBox.ValueChangedFcn = createCallbackFcn(app, @OptimizationGoalListBoxValueChanged, true);
            app.OptimizationGoalListBox.Value = 'acceleration, velocity, displacement';
            app.PlateauMinimumOctaveSpacingLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.PlateauMinimumOctaveSpacingLabel.Text = {'Plateau Minimum '; 'Octave Spacing'};
            app.PlateauMinimumOctaveSpacingListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.PlateauMinimumOctaveSpacingListBox.Items = {'One', 'Two-Thirds', 'One-Half', 'One-Third', 'One-Fourth'};
            app.PlateauMinimumOctaveSpacingListBox.ValueChangedFcn = createCallbackFcn(app, @PlateauMinimumOctaveSpacingListBoxValueChanged, true);
            app.PlateauMinimumOctaveSpacingListBox.Value = 'One-Half';
            app.OptimizedPSDOuputNameInternalExternalLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.OptimizedPSDOuputNameInternalExternalLabel.Text = {'Optimized PSD Ouput Name, '; 'Internal & External'};
            app.OptimizedPSDOuputNameInternalExternalEditField = uieditfield(app.PSDEnvelopeviaFDSTab, 'text');
            app.OptimizedPSDOuputNameInternalExternalEditField.Value = 'optimized_psd ';
            app.UITable_FDS_PSD = uitable(app.PSDEnvelopeviaFDSTab);
            app.UITable_FDS_PSD.ColumnName = {'FDS Array Name'; 'Q'; 'b'};
            app.UITable_FDS_PSD.RowName = {};
            app.UITable_FDS_PSD.ColumnEditable = true;
            app.UITable_FDS_PSD.CellEditCallback = createCallbackFcn(app, @UITable_FDS_PSDCellEdit, true);
            app.UITable_FDS_PSD.CellSelectionCallback = createCallbackFcn(app, @UITable_FDS_PSDCellSelection, true);
            app.UITable_FDS_PSD.DisplayDataChangedFcn = createCallbackFcn(app, @UITable_FDS_PSDDisplayDataChanged, true);
            app.UITable_FDS_PSD.SelectionChangedFcn = createCallbackFcn(app, @UITable_FDS_PSDSelectionChanged, true);
            app.ResultsTextAreaLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.PSDEnvelopeviaFDSTab);
            app.ExtractQbfromArrayNameButton = uibutton(app.PSDEnvelopeviaFDSTab, 'push');
            app.ExtractQbfromArrayNameButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractQbfromArrayNameButtonPushed, true);
            app.ExtractQbfromArrayNameButton.Text = 'Extract Q & b from Array Name';
            app.ExportOutputsButton = uibutton(app.PSDEnvelopeviaFDSTab, 'push');
            app.ExportOutputsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOutputsButtonPushed, true);
            app.ExportOutputsButton.Text = 'Export Outputs';
            app.SaveModelButton_env = uibutton(app.PSDEnvelopeviaFDSTab, 'push');
            app.SaveModelButton_env.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButton_envPushed, true);
            app.SaveModelButton_env.Text = 'Save Model';
            app.IncludeSMCS016MinimumWorkmanshipCheckBox = uicheckbox(app.PSDEnvelopeviaFDSTab);
            app.IncludeSMCS016MinimumWorkmanshipCheckBox.Text = 'Include SMC-S-016 Minimum Workmanship';
            app.RandomNumberSeedListBoxLabel = uilabel(app.PSDEnvelopeviaFDSTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.PSDEnvelopeviaFDSTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.FDS_Appver10byTomIrvineFatigueDamageSpectrumLabel = uilabel(app.UIFigure);
            app.FDS_Appver10byTomIrvineFatigueDamageSpectrumLabel.Text = 'FDS_App   ver 1.9   by Tom Irvine        Fatigue Damage Spectrum       Q = amplification factor      b = fatigue exponent';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'SDOF Response to Base Excitation via Smallwood ramp invariant digitial recursive filtering relationship & rainflow cycle counting & Miners-like cumulative damage index summation.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Rainflow counting method is ASTM E1049-85 (2017).  Option for deriving a PSD envelope which is useful for nonstationary data.';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SetSaveExportFolderButton = uibutton(app.UIFigure, 'push');
            app.SetSaveExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveExportFolderButtonPushed, true);
            app.SetSaveExportFolderButton.Text = 'Set Save/Export Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel = uilabel(app.UIFigure);
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel.Text = 'The Save/Export Folder must be outside of the vibrationdata_alt folder.   Note that is a slow function.';
            app.MsgEditFieldLabel = uilabel(app.UIFigure);
            app.MsgEditFieldLabel.Text = 'Msg';
            app.MsgEditField = uieditfield(app.UIFigure, 'text');
            app.MsgEditField.Editable = 'off';
            app.MsgEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FDS_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end