classdef Franken_method_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FrankenUIFigure               matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputSPLTab                   matlab.ui.container.Tab
        InputArrayFormat              matlab.ui.control.Label
        UnitsListBox                  matlab.ui.control.ListBox
        UnitsListBoxLabel             matlab.ui.control.Label
        SelectInputArrayListBox       matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton              matlab.ui.control.Button
        text1                         matlab.ui.control.Label
        listbox_method                matlab.ui.control.ListBox
        CalculatePSDTab               matlab.ui.container.Tab
        RingFrequencyHzReadOnlyEditField  matlab.ui.control.EditField
        RingFrequencyHzReadOnlyLabel  matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        WallThicknessEditField        matlab.ui.control.EditField
        WallThicknessEditFieldLabel   matlab.ui.control.Label
        DiameterEditField             matlab.ui.control.EditField
        DiameterEditFieldLabel        matlab.ui.control.Label
        MaterialListBox               matlab.ui.control.ListBox
        MaterialListBoxLabel          matlab.ui.control.Label
        MassDensityEditField          matlab.ui.control.EditField
        MassDensityEditFieldLabel     matlab.ui.control.Label
        ElasticModulusEditField       matlab.ui.control.EditField
        ElasticModulusEditFieldLabel  matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        SaveAccelPSDTab               matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        WorkspaceArrayNameEditField   matlab.ui.control.EditField
        WorkspaceArrayNameEditFieldLabel  matlab.ui.control.Label
        Franken_method_aver18byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        LoadModelButton               matlab.ui.control.Button
        SaveModelButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM % Description
        FS
        method
        iu
        imat
        Wa
    end
    
    methods (Access = private)

        function update(app)
            ClosePlots(app);
            try
                cell_array=update_spl();
            catch
                msgbox('updata_spl found no files');
            end
            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
                app.listbox_method.Value=app.listbox_method.Items{1}; 
            catch
            end
        end
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function Change(app)

            ClosePlots(app);
            app.SaveButton.Enable='off';
            
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            Items = strtrim(app.MaterialListBox.Items);
            im = strtrim(app.MaterialListBox.Value);
            app.imat = find(strcmp(Items, im));           
               
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.imat<=4)
                app.WallThicknessEditFieldLabel.Visible='on';
                app.WallThicknessEditField.Visible='on';
            else
                app.WallThicknessEditFieldLabel.Visible='off';
                app.WallThicknessEditField.Visible='off';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (lbf/in^2)';
                app.MassDensityEditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.DiameterEditFieldLabel.Text='Diameter (inch)';
                app.WallThicknessEditFieldLabel.Text='Wall Thickness (inch)';    
            else
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (Pa)';
                app.MassDensityEditFieldLabel.Text='Mass Density (kg/m^3)';
                app.DiameterEditFieldLabel.Text='Diameter (m)';                
                app.WallThicknessEditFieldLabel.Text='Wall Thickness (mm)';      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%

            app.ListBox.Visible='off';
                      
            if(app.imat==5)
              
                app.ElasticModulusEditFieldLabel.Text='Ring Frequency (Hz)';
               
                app.RingFrequencyHzReadOnlyEditField.Visible='off';
                app.RingFrequencyHzReadOnlyLabel.Visible='off';
                
                if(app.iu==1)
                    app.MassDensityEditFieldLabel.Text='Surface Mass Density';
                    app.ListBox.Visible='on';
                else
                    app.MassDensityEditFieldLabel.Text='Surface Mass Density (kg/m^2)';
                end   

            else
                app.RingFrequencyHzReadOnlyEditField.Visible='on';
                app.RingFrequencyHzReadOnlyLabel.Visible='on';
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% set(handles.edit_W,'String',' ');
            %% set(handles.edit_E,'String',' ');
            
            %
            if(app.imat==1)   % aluminum
                if(app.iu==1)
		            W=0.1;   % lbm/in^3     
                    E=1.0e+07; 
                else
                    W=2768;  % kg/m^3
                    E=6.891e+10; 
                end
            end
            %
            if(app.imat==2)   % steel
            
                if(app.iu==1)
		            W=0.29;  % lbm/in^3
                    E=3.0e+07;
                else
                    W=7850; % kg/m^3
                    E=2.067e+11;
                end
            end
            %
            %
            if(app.imat==3)   % Graphite/Epoxy
                if(app.iu==1)
		            W=0.058;   % lbm/in^3     
                    E=1.0e+07; 
                else
                    W=1605;  % kg/m^3
                    E=6.891e+10; 
                end
            end
            
            try
                ssw=sprintf('%8.4g',W);
                sse=sprintf('%8.4g',E);

                app.ElasticModulusEditField.Value=sse;
                app.MassDensityEditField.Value=ssw;
            catch
            end    

            
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            Change(app);
            app.SaveButton.Enable='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               try
                    update(app);             
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end   
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            ClosePlots(app);
            
            % fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    app.FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',app.FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end

            %%%

            disp(' ');
            disp(' * * * * * * ');
            disp(' ');
            
            f=app.THM(:,1);
            spl=app.THM(:,2);
            ilast=length(spl);
            
            dB=spl;
            %
            
            [oadb]=oaspl_function(dB);
            
            %
            fprintf('\n  Overall SPL = %8.4g dB \n\n',oadb);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            stationdiam=str2num(app.DiameterEditField.Value);
            
            if(app.iu==1)
                stationdiam=stationdiam/12.;  % convert inches to feet
            else
                stationdiam=stationdiam*3.28;  % convert meters to feet    
            end
            stationdiam_ft=stationdiam;
            
            if(app.imat<=4)
            %
                E=str2num(app.ElasticModulusEditField.Value);
                W=str2num(app.MassDensityEditField.Value);
            %
            
                if(app.iu==1)   % English
            %    
                    cmat = sqrt( (386*E/W) )/12;   % ft/sec
            %
                else        % metric
            %    
                    cmat = sqrt(E/W)*3.28084;      % m/sec -> ft/sec
            %     
                end
            
                if(app.iu==1) % English
                    W=W*(12.^3);      % convert from lbm/in^3 to lbm/ft^3  
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                thickness=str2num(app.WallThicknessEditField.Value);
                            
                if(isempty(thickness) || thickness<1.0e-10)
                   warndlg('Enter Thickness'); 
                   return; 
                end
                
                if(app.iu==1)
                    thickness=thickness/12.;    % convert from inch to feet
                else
                    thickness=thickness/1000;   % convert from mm to meters    
                end
            
                W=W*thickness;
            
                
                if(app.iu==1)
                % W   lbm/ft^2
                else
                    W=W*0.2048;  % convert from kg/m^2 to lbm/ft^2
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                W_lbm_per_ft2=W;
                cmat_ft_per_sec=cmat;
            
            
                [psd]=Franken_function(stationdiam_ft,W_lbm_per_ft2,cmat_ft_per_sec,f,spl);
            
                rf=cmat/(pi*stationdiam);
            
            end
            if(app.imat==5)
            %

                rf=str2num(app.ElasticModulusEditField.Value);
                W=str2num(app.WallThicknessEditField.Value);
                
                if(app.iu==1)
                    
                    Items = char(app.ListBox.Items);
                    im=app.ListBox.Value;
                    [nsmd]=find_index(Items,im);
                    
                    if(nsmd==2)
                        W=W*144;     % convert from lbm/in^2 to lbm/ft^2            
                    end
                    
                else
                    W=W*0.2048;  % convert from kg/m^2 to lbm/ft^2
                end
                W_lbm_per_ft2=W;
            
                [psd]=Franken_function_alt(stationdiam_ft,W_lbm_per_ft2,rf,f,spl);    
            %
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%k

            [~,i20]=min(abs(f-20));
            [~,i2k]=min(abs(f-2000));
            [~,i10k]=min(abs(f-10000));
            
            [~,grmsb] = calculate_PSD_slopes_no(f(i20:i10k,1),psd(i20:i10k));
            [~,grms] = calculate_PSD_slopes_no(f(i20:i2k),psd(i20:i2k));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf('\n Ring Frequency = %12.4g Hz \n',rf);
            
            ssr=sprintf('%8.4g',rf);
            app.RingFrequencyHzReadOnlyEditField.Value=ssr;

            %
            disp(' ');
            disp(' Overall Skin Levels ');
            %
            fprintf('\n\n Acceleration (up to 2000 Hz) = %12.4g GRMS \n',grms );
            %
            fprintf('\n Acceleration (up to 10,000 Hz) = %12.4g GRMS\n\n',grmsb );
            %

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.Wa=[f psd];
            
            fmin=f(1);
            
            t_string = sprintf(' Acceleration Power Spectral Density  %7.3g GRMS Overall',grms);
            x_label='Frequency (Hz)';
            y_label='Accel (G^2/Hz)'; 
            
            fmax=2000;
            
            % [fig_num,h]=plot_PSD_function(fig_num,x_label,y_label,t_string,Wa,fmin,fmax);
            
            ppp=app.Wa;
            md=6;
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            t_string = sprintf(' Acceleration Power Spectral Density  %7.3g GRMS Overall',grmsb);
            x_label='Frequency (Hz)';
            y_label='Accel (G^2/Hz)';
            
            fmax=10000;
            
            % [fig_num]=plot_PSD_function(fig_num,x_label,y_label,t_string,Wa,fmin,fmax);
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.imat<=4)
                app.RingFrequencyHzReadOnlyEditField.Visible='on';
                app.RingFrequencyHzReadOnlyLabel.Visible='on';
            else
                app.RingFrequencyHzReadOnlyEditField.Visible='off';
                app.RingFrequencyHzReadOnlyLabel.Visible='off';               
            end
            
    
            app.SaveButton.Enable='on';
           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if(isempty(app.Wa))
                warndlg('Calculate Acceleration PSD first');
                return;
            end

            try
                output_name=app.WorkspaceArrayNameEditField.Value;
                assignin('base', output_name, app.Wa);
                msgbox('Save Complete'); 
            catch
                warndlg('Save Failed');
            end
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Change(app);
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            ClosePlots(app);            
            app.SaveButton.Enable='off';
        end

        % Value changing function: ElasticModulusEditField
        function ElasticModulusEditFieldValueChanging(app, event)
            ClosePlots(app);            
            app.SaveButton.Enable='off';
        end

        % Value changing function: MassDensityEditField
        function MassDensityEditFieldValueChanging(app, event)
            ClosePlots(app);            
            app.SaveButton.Enable='off';
        end

        % Value changing function: DiameterEditField
        function DiameterEditFieldValueChanging(app, event)
            ClosePlots(app);            
            app.SaveButton.Enable='off';
        end

        % Value changing function: WallThicknessEditField
        function WallThicknessEditFieldValueChanging(app, event)
            ClosePlots(app);            
            app.SaveButton.Enable='off';
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            Change(app);
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
      
            try
                Franken.iu=app.UnitsListBox.Value;
            catch
            end    

            try
                Franken.imat=app.MaterialListBox.Value;
            catch
            end

            try
                Franken.smd=app.ListBox.Value;
            catch
            end

            E=app.ElasticModulusEditField.Value;
            W=app.MassDensityEditField.Value;

            diameter=app.DiameterEditField.Value;
            thickness=app.WallThicknessEditField.Value;
            
            
            Franken.E=E;
            Franken.W=W;
            Franken.diameter=diameter;
            Franken.thickness=thickness;
            
            try
                Franken.FS=app.FS;
            catch
            end

            try
	            Franken.THM=app.THM;
            catch
            end
            

            % % %
             
            structnames = fieldnames(Franken, '-full'); % fields in the struct
              
            % % %
             
               [writefname, writepname] = uiputfile('*.mat','Save data as');
             
               %%%    writepfname = fullfile(writepname, writefname);
                
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
               
                elk=sprintf('%s%s',writepname,writefname);
             
                try
             
                    save(elk, 'Franken'); 
             
                catch
                    warndlg('Save error');
                    return;
                end
             
            %%% SSS=load(elk)
             
            %%%%%%%%%%
            %%%%%%%%%%
             
            % Construct a questdlg with four options
            choice = questdlg('Save Complete.  Reset Workspace?', ...
                'Options', ...
                'Yes','No','No');
            % Handle response
            switch choice
                case 'Yes'
            %%        disp([choice ' Reseting'])
            %%        pushbutton_reset_Callback(hObject, eventdata, handles)
                    appdata = get(0,'ApplicationData');
                    fnsx = fieldnames(appdata);
                    for ii = 1:numel(fnsx)
                        rmappdata(0,fnsx{ii});
                    end
            end  

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
            disp(' ref 1');
             
            [filename, pathname] = uigetfile('*.mat');
             
            NAME = [pathname,filename];
             
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
             
            disp(' ref 2');
             
            k=length(structnames);
             
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
             
            disp(' ref 3');
             
            structnames
             
             
            % struct
             
            try
                Franken=evalin('base','Franken');
            catch
                warndlg(' evalin failed ');
                return;
            end
 

            try
                app.UnitsListBox.Value=Franken.iu;
            catch
            end

            try
                app.MaterialListBox.Value=Franken.imat;
            catch
            end

            ClosePlots(app);
            Change(app);
            app.SaveButton.Enable='off';

            try
                app.ListBox.Value=Franken.smd;
            catch
            end

            try
                app.ElasticModulusEditField.Value=Franken.E;
            catch
            end
            
            try
                app.MassDensityEditField.Value=Franken.W;
            catch
            end

            try
                app.DiameterEditField.Value=Franken.diameter;
            catch
            end
               
            try
                app.WallThicknessEditField.Value=Franken.thickness;
            catch
            end
            
            try
                app.FS=Franken.FS;
            catch
            end

            try
	            app.THM=Franken.THM;
            catch
            end

            try
                assignin('base', app.FS, app.THM);
            catch
            end

            try
                app.SelectInputArrayListBox.Value=app.FS;
            catch
            end

            update(app);

            msgbox('Load complete')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FrankenUIFigure = uifigure('Visible', 'off');
            app.FrankenUIFigure.Name = 'Franken';
            app.SaveModelButton = uibutton(app.FrankenUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.FrankenUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.ReturnButton = uibutton(app.FrankenUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Franken_method_aver18byTomIrvineLabel = uilabel(app.FrankenUIFigure);
            app.Franken_method_aver18byTomIrvineLabel.Text = 'Franken_method_a   ver 1.8   by Tom Irvine';
            app.TabGroup = uitabgroup(app.FrankenUIFigure);
            app.InputSPLTab = uitab(app.TabGroup);
            app.InputSPLTab.Title = 'Input SPL';
            app.listbox_method = uilistbox(app.InputSPLTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputSPLTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputSPLTab, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputSPLTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputSPLTab);
            app.SelectInputArrayListBox.Items = {''};
            app.SelectInputArrayListBox.Value = '';
            app.UnitsListBoxLabel = uilabel(app.InputSPLTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.InputSPLTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.InputArrayFormat = uilabel(app.InputSPLTab);
            app.InputArrayFormat.Text = 'InputArrayFormat';
            app.CalculatePSDTab = uitab(app.TabGroup);
            app.CalculatePSDTab.Title = 'Calculate PSD';
            app.CalculateButton_2 = uibutton(app.CalculatePSDTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ElasticModulusEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.ElasticModulusEditFieldLabel.Text = 'Elastic Modulus';
            app.ElasticModulusEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.ElasticModulusEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModulusEditFieldValueChanging, true);
            app.MassDensityEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.MassDensityEditFieldLabel.Text = 'Mass Density';
            app.MassDensityEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.MassDensityEditField.ValueChangingFcn = createCallbackFcn(app, @MassDensityEditFieldValueChanging, true);
            app.MaterialListBoxLabel = uilabel(app.CalculatePSDTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.CalculatePSDTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Graphite/Epoxy', 'Other 1', 'Other 2'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.DiameterEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.DiameterEditField.ValueChangingFcn = createCallbackFcn(app, @DiameterEditFieldValueChanging, true);
            app.WallThicknessEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.WallThicknessEditFieldLabel.Text = 'Wall Thickness';
            app.WallThicknessEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.WallThicknessEditField.ValueChangingFcn = createCallbackFcn(app, @WallThicknessEditFieldValueChanging, true);
            app.ListBox = uilistbox(app.CalculatePSDTab);
            app.ListBox.Items = {'lbm/ft^2', 'lbm/in^2'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'lbm/ft^2';
            app.RingFrequencyHzReadOnlyLabel = uilabel(app.CalculatePSDTab);
            app.RingFrequencyHzReadOnlyLabel.Text = {'Ring Frequency (Hz) '; 'Read Only'};
            app.RingFrequencyHzReadOnlyEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.RingFrequencyHzReadOnlyEditField.Editable = 'off';
            app.SaveAccelPSDTab = uitab(app.TabGroup);
            app.SaveAccelPSDTab.Title = 'Save Accel PSD';
            app.WorkspaceArrayNameEditFieldLabel = uilabel(app.SaveAccelPSDTab);
            app.WorkspaceArrayNameEditFieldLabel.Text = 'Workspace Array Name';
            app.WorkspaceArrayNameEditField = uieditfield(app.SaveAccelPSDTab, 'text');
            app.SaveButton = uibutton(app.SaveAccelPSDTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.FrankenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Franken_method_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FrankenUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FrankenUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FrankenUIFigure)
        end
    end
end