classdef Honeybee_vibe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClearButton                   matlab.ui.control.Button
        UpdateButton                  matlab.ui.control.Button
        WorkspaceListBox              matlab.ui.control.ListBox
        WorkspaceListBoxLabel         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SignalProcessingTab           matlab.ui.container.Tab
        AnalyzeButton                 matlab.ui.control.Button
        SelectListBox_signal          matlab.ui.control.ListBox
        SelectListBoxLabel            matlab.ui.control.Label
        StructuralDynamicsTab         matlab.ui.container.Tab
        AnalyzeButton_StructuralDynamics  matlab.ui.control.Button
        SelectAnalysisListBox         matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel    matlab.ui.control.Label
        GenerateTab                   matlab.ui.container.Tab
        AnalyzeButton_2               matlab.ui.control.Button
        SelectSignalListBox           matlab.ui.control.ListBox
        SelectSignalListBoxLabel      matlab.ui.control.Label
        FatigueTab                    matlab.ui.container.Tab
        PlottingTab                   matlab.ui.container.Tab
        AnalysisButton                matlab.ui.control.Button
        SelectPlotTypeListBox         matlab.ui.control.ListBox
        SelectPlotTypeListBoxLabel    matlab.ui.control.Label
        UtilitiesTab                  matlab.ui.container.Tab
        AnalyzeButton_Utilities       matlab.ui.control.Button
        SelectAnalysisListBox_Utilities  matlab.ui.control.ListBox
        SelectAnalysisListBox_2Label  matlab.ui.control.Label
        ImportTab                     matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        TextorExcelTab                matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        ReadFileButton                matlab.ui.control.Button
        matTab                        matlab.ui.container.Tab
        ReadmatButton                 matlab.ui.control.Button
        LibraryDataTab                matlab.ui.container.Tab
        ExportTab                     matlab.ui.container.Tab
        uipanel2                      matlab.ui.container.Panel
        listbox_format                matlab.ui.control.ListBox
        uipanel1                      matlab.ui.container.Panel
        PerformExportButton           matlab.ui.control.Button
        SelectInputArrayListBox       matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton              matlab.ui.control.Button
        pushbutton_return             matlab.ui.control.Button
        pushbutton_perform            matlab.ui.control.Button
        Honeybee_vibever10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
    end
    
    
    methods (Access = private)
        
        function update_list(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    cell_array{i} = vars(i).name;
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.WorkspaceListBox.Items=unique(sort(cell_array)); 
            app.SelectInputArrayListBox.Items=app.WorkspaceListBox.Items;
        end    

        
        function tab_change(app)
            if(contains(app.TabGroup.SelectedTab.Title,'Structural Dynamics'))
                sss={'SDOF Natural Frequency'; 
                     'SDOF Response to Steady Sine';
                     'SDOF Response to Classic Base Input';
                     'SDOF Response to Arbitrary Base Input'};
                app.SelectAnalysisListBox.Items=sss;
            end   
        end
        
        function signal_analysis(app)

            Items = char(app.SelectListBox_signal.Items);
            im=app.SelectListBox_signal.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                vibrationdata_statistics_Apph;
            end
            if(n==2)
                vibrationdata_fourier_transform_ah;
            end
            if(n==3)
                vibrationdata_psd_ah;
            end
            if(n==4)
                vibrationdata_tvfa_Apph;            
            end
            if(n==5)
                sine_curve_fit_ah;
            end    

        end
        
        function select_generate(app)
            Items = char(app.SelectSignalListBox.Items);
            im=app.SelectSignalListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                signal_sine_a;
            end
            if(n==2)
                signal_sine_sweep_a;
            end
            if(n==3)
                white_noise_a;
            end
            if(n==4)
       
            end    
        end
        
        function SelectSD(app)
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                sdof_fn_App;
            end    
            if(n==2)
                steady_App_1;
            end
            if(n==3)
                classical_pulse_base_input_a;        
            end    
            if(n==4)
                vibrationdata_sdof_base_Apph; 
            end              
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_list(app);
            tab_change(app);
            assignin('base','myHandle',app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            signal_analysis(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update_list(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                sss=sprintf('clear %s',cell_array{i});
                evalin('base', sss);
            end    
            update_list(app);

        end

        % Button pushed function: AnalyzeButton_StructuralDynamics
        function AnalyzeButton_StructuralDynamicsPushed(app, event)
            
            SelectSD(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            update_list(app);
            tab_change(app);
        end

        % Button pushed function: AnalyzeButton_Utilities
        function AnalyzeButton_UtilitiesPushed(app, event)
            Items = char(app.SelectAnalysisListBox_Utilities.Items);
            im=app.SelectAnalysisListBox_Utilities.Value;
            [n]=find_index(Items,im);

            if(n==1)
                vibrationdata_unit_conversion_App;
            end    
            if(n==2)
                amplitude_conversion_utilities_a;
            end    
            if(n==3)
                peak_sigma_random_App;
            end    

        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            select_generate(app);
        end

        % Value changed function: SelectListBox_signal
        function SelectListBox_signalValueChanged(app, event)
            signal_analysis(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_list(app);
        end

        % Button pushed function: PerformExportButton
        function PerformExportButtonPushed(app, event)
            
            try
                FS=app.SelectInputArrayListBox.Value;
                av=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.')
                return;
            end
            
            Items = char(app.listbox_format.Items);
            im=app.listbox_format.Value;
            [n]=find_index(Items,im);
            
            try
                if(n==1) % ascii
                
                    [writefname, writepname] = uiputfile('*.txt','Save data as');
                    writepfname = fullfile(writepname, writefname);
    
                    writematrix(av,writepfname,'Delimiter','\t');
                
                end
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                if(n==2)  % excel
                
                    [writefname, writepname] = uiputfile('*.xls','Save model as Excel file');
                    writepfname = fullfile(writepname, writefname);
                
                    writematrix(av,writepfname);
                
                end
                if(n==3) % mat
                
                    [writefname, writepname] = uiputfile('*.mat','Save data as');
                    
                    pattern = '.mat';
                    replacement = '';
                    sname=regexprep(writefname,pattern,replacement);
                    ss=sprintf('%s',sname);
                    ss1=sprintf('%s',sname);
                      
                    FS2=FS;        
                    FS2=evalin('base',FS2);
                               
                    out1=sprintf('%s=FS2;',ss);
                    
                    clear ss;
                    
                    eval(out1);
                    
                    elk=sprintf('%s%s',writepname,writefname);
                    
                    save(elk,ss1); 
    
                end
                msgbox('Export complete');
            catch
                warndlg('Export failed');
                return;
            end

        end

        % Value changed function: SelectSignalListBox
        function SelectSignalListBoxValueChanged(app, event)
            select_generate(app);
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
            SelectSD(app);
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);

                   app.THM = readmatrix(filename);

                   msgbox('File read');              

            catch
                   warndlg('Read unsuccessful');
                   return;
            end

            try
                    [~, name, ~] = fileparts(filename);
            catch
                    warndlg('ref 1')
            end

            try
                    assignin('base',name,app.THM);
            catch
                    warndlg('ref 2')
            end

            update_list(app);
        end

        % Button pushed function: ReadmatButton
        function ReadmatButtonPushed(app, event)
            try
            
                [filename, pathname] = uigetfile('*.mat');
     
                NAME = [pathname,filename];
                struct=load(NAME);
                structnames = fieldnames(struct, '-full'); % fields in the struct
                
                k=length(structnames);
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end
                
                update_list(app);
                msgbox('Load complete')

            catch

                warndlg('Read failed');
            end    
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            plot_time_history_ah;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Honeybee_vibever10byTomIrvineLabel = uilabel(app.UIFigure);
            app.Honeybee_vibever10byTomIrvineLabel.Text = 'Honeybee_vibe     ver 1.0   by Tom Irvine';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SignalProcessingTab = uitab(app.TabGroup);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.SelectListBoxLabel = uilabel(app.SignalProcessingTab);
            app.SelectListBoxLabel.Text = 'Select';
            app.SelectListBox_signal = uilistbox(app.SignalProcessingTab);
            app.SelectListBox_signal.Items = {'Statistics', 'Fourier Transform & FFT', 'Power Spectral Density', 'Time Varying Statistics', 'Sine & Damped Sine Curve-fit'};
            app.SelectListBox_signal.ValueChangedFcn = createCallbackFcn(app, @SelectListBox_signalValueChanged, true);
            app.SelectListBox_signal.Value = 'Statistics';
            app.AnalyzeButton = uibutton(app.SignalProcessingTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StructuralDynamicsTab = uitab(app.TabGroup);
            app.StructuralDynamicsTab.Title = 'Structural Dynamics';
            app.SelectAnalysisListBoxLabel = uilabel(app.StructuralDynamicsTab);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.StructuralDynamicsTab);
            app.SelectAnalysisListBox.Items = {'Item 1'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.AnalyzeButton_StructuralDynamics = uibutton(app.StructuralDynamicsTab, 'push');
            app.AnalyzeButton_StructuralDynamics.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_StructuralDynamicsPushed, true);
            app.AnalyzeButton_StructuralDynamics.Text = 'Analyze';
            app.GenerateTab = uitab(app.TabGroup);
            app.GenerateTab.Title = 'Generate';
            app.SelectSignalListBoxLabel = uilabel(app.GenerateTab);
            app.SelectSignalListBoxLabel.Text = 'Select Signal';
            app.SelectSignalListBox = uilistbox(app.GenerateTab);
            app.SelectSignalListBox.Items = {'Sine', 'Sine Sweep', 'White Noise', 'Random Skewness & Kurtosis'};
            app.SelectSignalListBox.ValueChangedFcn = createCallbackFcn(app, @SelectSignalListBoxValueChanged, true);
            app.SelectSignalListBox.Value = 'Sine';
            app.AnalyzeButton_2 = uibutton(app.GenerateTab, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.FatigueTab = uitab(app.TabGroup);
            app.FatigueTab.Title = 'Fatigue';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.SelectPlotTypeListBoxLabel = uilabel(app.PlottingTab);
            app.SelectPlotTypeListBoxLabel.Text = 'Select Plot Type';
            app.SelectPlotTypeListBox = uilistbox(app.PlottingTab);
            app.SelectPlotTypeListBox.Items = {'Time History'};
            app.SelectPlotTypeListBox.Value = 'Time History';
            app.AnalysisButton = uibutton(app.PlottingTab, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.SelectAnalysisListBox_2Label = uilabel(app.UtilitiesTab);
            app.SelectAnalysisListBox_2Label.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Utilities = uilistbox(app.UtilitiesTab);
            app.SelectAnalysisListBox_Utilities.Items = {'Unit Conversion', 'Sine & SRS Amplitude Conversion', 'Peak Response Random Vibration'};
            app.SelectAnalysisListBox_Utilities.Value = 'Unit Conversion';
            app.AnalyzeButton_Utilities = uibutton(app.UtilitiesTab, 'push');
            app.AnalyzeButton_Utilities.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_UtilitiesPushed, true);
            app.AnalyzeButton_Utilities.Text = 'Analyze';
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.TabGroup2 = uitabgroup(app.ImportTab);
            app.TextorExcelTab = uitab(app.TabGroup2);
            app.TextorExcelTab.Title = 'Text or Excel';
            app.ReadFileButton = uibutton(app.TextorExcelTab, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.Label = uilabel(app.TextorExcelTab);
            app.Label.Text = {'A = readmatrix(filename) creates an array by reading column-oriented '; 'data from a file. The readmatrix function performs automatic detection '; 'of import parameters for your file.'; ''; 'readmatrix determines the file format from the file extension:'; ''; '.txt, .dat, or .csv for delimited text files'; ''; '.xls, .xlsb, .xlsm, .xlsx, .xltm, .xltx, or .ods for spreadsheet files'; ''; 'For files containing mixed numeric and text data, readmatrix imports '; 'the data as a numeric array by default'};
            app.matTab = uitab(app.TabGroup2);
            app.matTab.Title = 'mat';
            app.ReadmatButton = uibutton(app.matTab, 'push');
            app.ReadmatButton.ButtonPushedFcn = createCallbackFcn(app, @ReadmatButtonPushed, true);
            app.ReadmatButton.Text = 'Read .mat';
            app.LibraryDataTab = uitab(app.TabGroup2);
            app.LibraryDataTab.Title = 'Library Data';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.pushbutton_perform = uibutton(app.ExportTab, 'push');
            app.pushbutton_perform.Tag = 'pushbutton_perform';
            app.pushbutton_perform.Text = 'Perform Export';
            app.pushbutton_return = uibutton(app.ExportTab, 'push');
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel1 = uipanel(app.ExportTab);
            app.uipanel1.Title = 'Existing Matlab Workspace Array Name';
            app.uipanel1.Tag = 'uipanel1';
            app.UpdateListButton = uibutton(app.uipanel1, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.uipanel1);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.uipanel1);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.PerformExportButton = uibutton(app.uipanel1, 'push');
            app.PerformExportButton.ButtonPushedFcn = createCallbackFcn(app, @PerformExportButtonPushed, true);
            app.PerformExportButton.Text = 'Perform Export';
            app.uipanel2 = uipanel(app.ExportTab);
            app.uipanel2.Title = 'Select Export Format';
            app.uipanel2.Tag = 'uipanel2';
            app.listbox_format = uilistbox(app.uipanel2);
            app.listbox_format.Items = {'ASCII Text', 'Excel               ', 'mat'};
            app.listbox_format.Tag = 'listbox_format';
            app.listbox_format.Value = 'Excel               ';
            app.WorkspaceListBoxLabel = uilabel(app.UIFigure);
            app.WorkspaceListBoxLabel.Text = 'Workspace';
            app.WorkspaceListBox = uilistbox(app.UIFigure);
            app.WorkspaceListBox.Items = {};
            app.WorkspaceListBox.Value = {};
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Honeybee_vibe

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end