classdef Martin_Marietta_shock_distance_joints_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        pushbutton_return      matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        text40                 matlab.ui.control.Label
        listbox_CV             matlab.ui.control.ListBox
        pushbutton_load_model  matlab.ui.control.Button
        pushbutton_save_model  matlab.ui.control.Button
        text39                 matlab.ui.control.Label
        listbox_rp             matlab.ui.control.ListBox
        pushbutton_calculate   matlab.ui.control.Button
        pushbutton_reset       matlab.ui.control.Button
        text38                 matlab.ui.control.Label
        uitable_data           matlab.ui.control.Table
        edit_title             matlab.ui.control.EditField
        text25                 matlab.ui.control.Label
        edit_input_array       matlab.ui.control.EditField
        text24                 matlab.ui.control.Label
        text23                 matlab.ui.control.Label
        listbox_num            matlab.ui.control.ListBox
        listbox_units          matlab.ui.control.ListBox
        text22                 matlab.ui.control.Label
        uipanel_save           matlab.ui.container.Panel
        text21                 matlab.ui.control.Label
        pushbutton_save        matlab.ui.control.Button
        edit_output_array      matlab.ui.control.EditField
        text2                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function change(app, hObject, eventdata, handles)
            
            set(handles.uipanel_save,'Visible','off');
            
            
            iu=get(handles.listbox_units,'Value');
            
            
            Ncolumns=3;
            
            Nrows=get(handles.listbox_num,'Value');
            
            A=get(handles.uitable_data,'Data');
            
            [data_s]=clear_data_s(app, Nrows);
            
            
            if(~isempty(A))
            
                sz=size(A);
                Arows=sz(1);
            
                M=min([ Arows Nrows ]);
            
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end
                end
            
            end
            
            if(iu==1)
                cn={'Type','Delta Distance (in)','Plateau Remaining Ratio'};
            else
                cn={'Type','Delta Distance (m)','Plateau Remaining Ratio'};
            end
            
            try
                set(handles.uitable_data,'Data',data_s,'ColumnName',cn);
            catch
                warndlg('set failed');
                return;
            end
        end
        
        function data_s = clear_data_s(app, Nrows)
            
            for i=1:Nrows
                    data_s{i,1}='';
                    data_s{i,2}='';
                    data_s{i,3}='';
            end
        end
        
        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_input_array (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit11_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_title (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_input_array_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_input_array and none of its controls.
            
            % hObject    handle to edit_input_array (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s1_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s1 and none of its controls.
            
            % hObject    handle to edit_s1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s2_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s2 and none of its controls.
            
            % hObject    handle to edit_s2 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s3_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s3_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s3 and none of its controls.
            
            % hObject    handle to edit_s3 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s4_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s4_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s4 and none of its controls.
            
            % hObject    handle to edit_s4 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s5_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s5_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s5 and none of its controls.
            
            % hObject    handle to edit_s5 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function edit_s6_CreateFcn(~, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_s6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_s6_KeyPressFcn(app, hObject, eventdata, handles)
            % --- Executes on key press with focus on edit_s6 and none of its controls.
            
            % hObject    handle to edit_s6 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel_save,'Visible','off');
        end
        
        function listbox13_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox15_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s1_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s1.
            
            % hObject    handle to listbox_s1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s1
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s2_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s2.
            
            % hObject    handle to listbox_s2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s2
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s3_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s3.
            
            % hObject    handle to listbox_s3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s3
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s3_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s4_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s4.
            
            % hObject    handle to listbox_s4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s4 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s4
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s4_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s5_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s5.
            
            % hObject    handle to listbox_s5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s5 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s5
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s5_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_s6_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_s6.
            
            % hObject    handle to listbox_s6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_s6 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_s6
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_s6_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_s6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_steps_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in listbox_steps.
            
            % hObject    handle to listbox_steps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_steps contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_steps
            
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_steps_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox_steps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable_data.ColumnFormat = {{'Cylindrical Shell' 'Longeron or Stringer' 'Skin/Ring Frame' 'Primary Truss' 'Complex Airframe' 'Complex Equipment Structure' 'Honeycomb' 'Joint'} 'char' 'char'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Martin_Marietta_shock_distance_joints_OpeningFcn(app, varargin)
            % --- Executes just before Martin_Marietta_shock_distance_joints is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Martin_Marietta_shock_distance_joints (see VARARGIN)
            
            % Choose default command line output for Martin_Marietta_shock_distance_joints
            handles.output = hObject;
            
            pushbutton_reset_Callback(hObject, eventdata, handles);
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_num
        function listbox_num_Callback(app, event)
            % --- Executes on selection change in listbox_num.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_num (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_num contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_num
            change(app, hObject, eventdata, handles);
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            % --- Executes on selection change in listbox_units.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_units contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_units
            
            change(app, hObject, eventdata, handles);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % --- Executes on button press in pushbutton_calculate.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            disp('  ');
            disp(' * * * * * * * * * * * ');
            disp('  ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            CV=get(handles.listbox_CV,'Value');
            
            iu=get(handles.listbox_units,'Value');
            
            
            A=get(handles.uitable_data,'Data');
            
            Ncolumns=3;
            Nrows=get(handles.listbox_num,'Value');
            
            k=1;
            for i=1:Nrows
                channela{i}=A{k}; k=k+1;
            end
            for i=1:Nrows
                channelb{i}=A{k}; k=k+1;
            end
            
            for i=1:Nrows
                channelc{i}=A{k}; k=k+1;
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            md=5;
            
            t_string=get(handles.edit_title,'String');
            
            try
                  FS=get(handles.edit_input_array,'String');
                  THM=evalin('base',FS);
            %      disp('ref 2')
            catch
                  warndlg('Unable to read input file','Warning');
                  return;
            end
            
            f=THM(:,1);
            a=THM(:,2);
            
            srs1=THM;
            
            nrp=get(handles.listbox_rp,'Value');
            
            %%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Source SRS ');
            disp('  ');
            disp('  fn(Hz)  Accel(G) ');
            
            for i=1:length(f)
                out1=sprintf(' %6.0f  %8.4g ',srs1(i,1),srs1(i,2));
                disp(out1);
            end
            
            x1=0;
            
            for i=1:Nrows
            
                ca=channela{i};
                cb=char(channelb{i});
                cc=char(channelc{i});
            
                if(contains(ca,'Joint'))
            
                    v=str2double(cc);
            
                    out1=sprintf('\n Step %d   Joint   %g plateau remaining',i,v);
                    disp(out1);
            
                    [srs2]=SRS_plateau_attenuation(srs1,v);
            
                else
            
                    v=str2double(cb);
            
                    if(contains(ca,'Cylindrical Shell'))
                        sq='Cylindrical Shell';
                        n=1;
                    end
                    if(contains(ca,'Longeron or Stringer'))
                        sq='Longeron or Stringer';
                        n=2;
                    end
                    if(contains(ca,'Skin/Ring Frame'))
                        sq='Skin/Ring Frame';
                        n=3;
                    end
                    if(contains(ca,'Primary Truss'))
                        sq='Primary Truss';
                        n=4;
                    end
                    if(contains(ca,'Complex Airframe'))
                        sq='Complex Airframe';
                        n=5;
                    end
                    if(contains(ca,'Complex Equipment Structure'))
                        sq='Complex Equipment Structure';
                        n=6;
                    end
                    if(contains(ca,'Honeycomb'))
                        sq='Honeycomb';
                        n=7;
                    end
            
                    if(iu==1)
                        out1=sprintf('\n Step %d  %s   Delta Distance=%g in',i,sq,v);
                        disp(out1);
                    else
                        out1=sprintf('\n Step %d  %s   Delta Distance=%g m',i,sq,v);
                        disp(out1);
                    end
            
                    x2=v+x1;
            
                    xx1=x1;
                    xx2=x2;
            
                    if(iu==2)
                        xx1=x1*100/2.54;
                        xx2=x2*100/2.54;
                    end
            
                    [attr1,attp1]=MM_ramp_plateau(xx1,n);
                    [attr2,attp2]=MM_ramp_plateau(xx2,n);
            
                    if(attr2==0 || attr1==0)
                        attr=0;
                    else
                        attr=attr2/attr1;
                    end
            
                    if(attp2==0 || attp1==0)
                        attp=0;
                    else
                        attp=attp2/attp1;
                    end
            
                    if(nrp==2)
                        attr=attp;
                    end
            
                    if(attr<attp)
                        attr=attp;
                    end
            
                    out1=sprintf('\n  Remaining:  ramp=%6.3g   plateau=%6.3g ',attr,attp);
                    disp(out1);
            
                    f=srs1(:,1);
                    a=srs1(:,2);
            
                    [f,a]=srs_distance_atten_ramp_plateau(attp,attr,f,a);
                    srs2=[f a];
            
                    x1=x2;
            
                end
            
                srs1=srs2;
            
                disp('  ');
                disp('  fn(Hz)  Accel(G) ');
            
                f=srs1(:,1);
                for i=1:length(f)
                    out1=sprintf(' %6.0f  %8.4g ',srs1(i,1),srs1(i,2));
                    disp(out1);
                end
            
            end
            
            %%%%%%%%%%%%%%%%%%%
            
            setappdata(0,'attenuated_srs',srs1);
            
            set(handles.uipanel_save,'Visible','on');
            
            %%%%%%%%%%%%%%%%%%%
            
            ppp1=THM;
            ppp2=srs1;
            
            leg1='Source';
            leg2='Attenuated';
            
            fmin=f(1);
            fmax=f(end);
            
            %%%
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            
            
            if(CV==1)
            
                [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
            else
                ppp50=[fmin 0.8*fmin; fmax 0.8*fmax ];
                leg50='50 ips';
            
                [fig_num,h2]=plot_loglog_function_md_three_h2_third_dash(fig_num,x_label,...
                              y_label,t_string,ppp1,ppp2,ppp50,leg1,leg2,leg50,fmin,fmax,md);
            end
        end

        % Button pushed function: pushbutton_load_model
        function pushbutton_load_model_Callback(app, event)
            % --- Executes on button press in pushbutton_load_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_load_model (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            [filename, pathname] = uigetfile('*.mat', 'Select plot save file');
            
            NAME = [pathname,filename];
            
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
            
            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
            
            MMshock=evalin('base','MMshock');
            
            catch
               warndlg(' evalin failed ');
               return;
            end
            
            %%%%%%%%%%%%
            
            try
                output=MMshock.output;
                set(handles.edit_output_array,'String',output);
            catch
            end
            
            try
                iu=MMshock.iu;
                set(handles.listbox_units,'Value',iu);
            catch
            end
            
            try
                input_array=MMshock.input_array;
                set(handles.edit_input_array,'String',input_array);
            catch
            end
            
            try
                title=MMshock.title;
                set(handles.edit_title,'String',title);
            catch
            end
            
            try
                rp=MMshock.rp;
                set(handles.listbox_rp,'Value',rp);
            catch
            end
            
            try
                num=MMshock.num;
                set(handles.listbox_num,'Value',num);
            catch
            end
            
            try
                data=MMshock.data;
                set(handles.uitable_data,'data',data);
            catch
            end
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_reset_Callback(app, event)
            % --- Executes on button press in pushbutton_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            Nrows=get(handles.listbox_num,'Value');
            
            [data_s]=clear_data_s(app, Nrows);
            
            set(handles.uitable_data,'Data',data_s);
            
            listbox_num_Callback(hObject, eventdata, handles);
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            % --- Executes on button press in pushbutton_return.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_return (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(Martin_Marietta_shock_distance_joints);
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % --- Executes on button press in pushbutton_save.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data=getappdata(0,'attenuated_srs');
            
            output_name=get(handles.edit_output_array,'String');
            assignin('base', output_name, data);
            
            output_name_6dB=sprintf('%s_6dB',output_name);
            assignin('base', output_name_6dB, [data(:,1) 2*data(:,2)]);
            
            h = msgbox('Save Complete');
        end

        % Button pushed function: pushbutton_save_model
        function pushbutton_save_model_Callback(app, event)
            % --- Executes on button press in pushbutton_save_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save_model (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                output=get(handles.edit_output_array,'String');
                MMshock.output=output;
            catch
            end
            
            try
                iu=get(handles.listbox_units,'Value');
                MMshock.iu=iu;
            catch
            end
            
            try
                input_array=get(handles.edit_input_array,'String');
                MMshock.input_array=input_array;
            catch
            end
            
            try
                title=get(handles.edit_title,'String');
                MMshock.title=title;
            catch
            end
            
            try
                rp=get(handles.listbox_rp,'Value');
                MMshock.rp=rp;
            catch
            end
            
            try
                num=get(handles.listbox_num,'Value');
                MMshock.num=num;
            catch
            end
            
            try
                data=get(handles.uitable_data,'data');
                MMshock.data=data;
            catch
            end


            % % %
            
            structnames = fieldnames(MMshock, '-full'); % fields in the struct
            
            % % %
            
               [writefname, writepname] = uiputfile('*.mat','Save data as');
            
               %%%    writepfname = fullfile(writepname, writefname);
            
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
            
                elk=sprintf('%s%s',writepname,writefname);
            
            
                try
            
                    save(elk, 'MMshock');
            
                catch
                    warndlg('Save error');
                    return;
                end
            
            %%% SSS=load(elk)
            
            %%%%%%%%%%
            %%%%%%%%%%
            
            msgbox('Save Complete');
        end

        % Cell edit callback: uitable_data
        function uitable_data_CellEditCallback(app, event)
            % --- Executes when entered data in editable cell(s) in uitable_data.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitable_data (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            %%pushbutton_change(hObject, eventdata, handles);
            
            % disp('edit')
            
            A=get(handles.uitable_data,'Data');
            
            Ncolumns=3;
            Nrows=get(handles.listbox_num,'Value');
            
            k=1;
            for i=1:Nrows
                channela{i}=A{k}; k=k+1;
            end
            for i=1:Nrows
                channelb{i}=A{k}; k=k+1;
            end
            
            for i=1:Nrows
                channelc{i}=A{k}; k=k+1;
            end


            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}=A{i,j};
                end
            end
            
            for i=1:Nrows
                ca=channela{i};
                cb=channelb{i};
                cc=channelc{i};
                if(contains(ca,'Joint'))
                        data_s{i,2}='n/a';
                        if(contains(cc,'n/a'))
                            data_s{i,3}='';
                        end
                else
                        data_s{i,3}='n/a';
                        if(contains(cb,'n/a'))
                            data_s{i,2}='';
                        end
                end
            end
            
            
            set(handles.uitable_data,'Data',data_s);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Martin_Marietta_shock_distance_joints';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Martin_Marietta_shock_distance_joints.m  ver 1.6  by Tom Irvine       The input SRS must have two columns:  fn (Hz) & Accel (G)';
            app.uipanel_save = uipanel(app.figure1);
            app.uipanel_save.Title = 'Export Attenuated SRS to Matlab';
            app.uipanel_save.Tag = 'uipanel_save';
            app.edit_output_array = uieditfield(app.uipanel_save, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            app.pushbutton_save = uibutton(app.uipanel_save, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.text21 = uilabel(app.uipanel_save);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Enter Attenuated SRS Array Name';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Enter Data';
            app.uipanel3.Tag = 'uipanel3';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Units';
            app.listbox_units = uilistbox(app.uipanel3);
            app.listbox_units.Items = {'English', 'Metric'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'English';
            app.listbox_num = uilistbox(app.uipanel3);
            app.listbox_num.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.listbox_num.ValueChangedFcn = createCallbackFcn(app, @listbox_num_Callback, true);
            app.listbox_num.Tag = 'listbox_num';
            app.listbox_num.Value = '1';
            app.text23 = uilabel(app.uipanel3);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Number of Attenuation Steps';
            app.text24 = uilabel(app.uipanel3);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Enter Input SRS Array Name';
            app.edit_input_array = uieditfield(app.uipanel3, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text25 = uilabel(app.uipanel3);
            app.text25.Tag = 'text25';
            app.text25.Text = 'SRS Plot Title';
            app.edit_title = uieditfield(app.uipanel3, 'text');
            app.edit_title.Tag = 'edit_title';
            app.edit_title.Value = 'SRS Q=10';
            app.uitable_data = uitable(app.uipanel3);
            app.uitable_data.ColumnName = {'Type'; 'Delta Distance (in)'; 'Plateau Remaining Ratio'};
            app.uitable_data.ColumnEditable = [true true true];
            app.uitable_data.CellEditCallback = createCallbackFcn(app, @uitable_data_CellEditCallback, true);
            app.uitable_data.Tag = 'uitable_data';
            app.text38 = uilabel(app.uipanel3);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Joint remaining ratio is typically about 0.7.      Recommended number of joints is 3 maximum.';
            app.pushbutton_reset = uibutton(app.uipanel3, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_reset_Callback, true);
            app.pushbutton_reset.Tag = 'pushbutton_reset';
            app.pushbutton_reset.Text = 'Reset';
            app.pushbutton_calculate = uibutton(app.uipanel3, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.listbox_rp = uilistbox(app.uipanel3);
            app.listbox_rp.Items = {'No', 'Yes'};
            app.listbox_rp.Tag = 'listbox_rp';
            app.listbox_rp.Value = 'No';
            app.text39 = uilabel(app.uipanel3);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Set Ramp Attenuation to Plateau Attenuation';
            app.pushbutton_save_model = uibutton(app.uipanel3, 'push');
            app.pushbutton_save_model.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_model_Callback, true);
            app.pushbutton_save_model.Tag = 'pushbutton_save_model';
            app.pushbutton_save_model.Text = 'Save Model';
            app.pushbutton_load_model = uibutton(app.uipanel3, 'push');
            app.pushbutton_load_model.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_model_Callback, true);
            app.pushbutton_load_model.Tag = 'pushbutton_load_model';
            app.pushbutton_load_model.Text = 'Load Model';
            app.listbox_CV = uilistbox(app.uipanel3);
            app.listbox_CV.Items = {'No', 'Yes'};
            app.listbox_CV.Tag = 'listbox_CV';
            app.listbox_CV.Value = 'No';
            app.text40 = uilabel(app.uipanel3);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Add 50 ips Line';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Martin_Marietta_shock_distance_joints_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Martin_Marietta_shock_distance_joints_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end