classdef RTV_FAS_SRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RVTFASSRSUIFigure              matlab.ui.Figure
        RandomVibrationTheoryLabel_4   matlab.ui.control.Label
        RandomVibrationTheoryLabel_3   matlab.ui.control.Label
        RandomVibrationTheoryLabel_2   matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        EnterFourierTransformTab       matlab.ui.container.Tab
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        CalculateSRSTab                matlab.ui.container.Tab
        OutputNameEditField            matlab.ui.control.EditField
        OutputNameEditFieldLabel       matlab.ui.control.Label
        GroundMotionDurationsecEditField  matlab.ui.control.EditField
        GroundMotionDurationsecEditFieldLabel  matlab.ui.control.Label
        EndFreqHzEditField             matlab.ui.control.EditField
        EndFreqHzEditFieldLabel        matlab.ui.control.Label
        StartFreqHzEditField           matlab.ui.control.EditField
        StartFreqHzEditFieldLabel      matlab.ui.control.Label
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        RandomVibrationTheoryLabel     matlab.ui.control.Label
        RTV_FAS_SRSver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM    % Description
        method %
    end
    
    methods (Access = private)

        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;        
            end
        end

       function update(app)
            [cell_array]=update_FFT();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
       end        
        
       function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.method=1;
            ClosePlots(app);
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            fig_num=1;
            
            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end

            Dgm=str2num(app.GroundMotionDurationsecEditField.Value);

            QS=app.AmplificationFactorQEditField.Value;
            
            Q=str2num(QS);
            fstart=str2num(app.StartFreqHzEditField.Value);
            fend=str2num(app.EndFreqHzEditField.Value);

            damp=1/(2*Q);

            freq=app.THM(:,1);
            df=mean(diff(freq));
            y=app.THM(:,2);
            y_psd=0.5*(y.^2/df);

            num=length(freq);
            
            % Apply the SDOF transfer function.  then spectral moment calculation.
            
            noct=ceil(12*log2(fend/fstart));
            
            fn=zeros(noct,1);
            fn(1)=fstart;
            
            for i=2:noct
                fn(i)=fn(i-1)*2^(1/12);
            end

            if(fn(noct)<fend)
                fn(end+1)=fend;
                noct=noct+1;
            end
            
            
            amax=zeros(noct,1);
            
            fprintf('\n fn(Hz)  m4/m2  Ne  e  pf\n');

            progressbar;

            for i=1:noct
            
                progressbar(i/noct);

                x_psd=zeros(num,1);
            
                for j=1:num
                    [accel_complex,rd_complex,accel_mag,rd_mag]=...
                                                      sdof_trans_base(freq(j),fn(i),damp);
            
                    x_psd(j)=y_psd(j)*accel_mag^2;
                end
                            
                [EP,vo,m0,m1,m2,m4,alpha2,e]=spectal_moments_alt(freq,x_psd,df);

                Ne=Dgm*(sqrt(m4/m2))/pi;
                e=sqrt(m2^2/(m0*m4));
            
                fun = @(x) (   1-(1-e*exp(-x.^2)).^Ne  );
                pf = sqrt(2)*integral(fun,0,Inf);

                fprintf(' %7.3g  %7.3g %7.3g %7.3g %7.3g \n',fn(i),m4/m2,Ne,e,pf)
            
                To=1/fn(i);
                Do=To/(2*pi*damp);
                n=3;
                alpha=1/3;
                
                gamma=Dgm/To;
                
                A=gamma^n;
                B=A+alpha;
            
                Drms=Dgm+Do*(A/B);
            
                arms=sqrt(m0*Dgm/Drms);
            
                amax(i)=pf*arms;
            
            end

            progressbar(1);
            
            fn=fix_size(fn);
            amax=fix_size(amax);
            
            asrs=[fn amax];
            
            ppp=asrs;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            t_string=sprintf('SRS Q=%s',QS);
            fmin=fstart;
            fmax=fend;
            [fig_num,h2]=plot_loglog_function_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax);
            
            output_name=app.OutputNameEditField.Value;
            assignin('base',output_name, ppp);   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RVTFASSRSUIFigure = uifigure('Visible', 'off');
            app.RVTFASSRSUIFigure.Name = 'RVT FAS SRS';
            app.RTV_FAS_SRSver10byTomIrvineLabel = uilabel(app.RVTFASSRSUIFigure);
            app.RTV_FAS_SRSver10byTomIrvineLabel.Text = 'RTV_FAS_SRS     ver 1.0  by Tom Irvine';
            app.RandomVibrationTheoryLabel = uilabel(app.RVTFASSRSUIFigure);
            app.RandomVibrationTheoryLabel.Text = 'Random Vibration Theory, Fourier Amplitude Spectrum, Shock Response Spectrum (Earthquake Engineering)';
            app.TabGroup = uitabgroup(app.RVTFASSRSUIFigure);
            app.EnterFourierTransformTab = uitab(app.TabGroup);
            app.EnterFourierTransformTab.Title = 'Enter Fourier Transform';
            app.listbox_method = uilistbox(app.EnterFourierTransformTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.EnterFourierTransformTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.EnterFourierTransformTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.EnterFourierTransformTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.EnterFourierTransformTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.CalculateSRSTab = uitab(app.TabGroup);
            app.CalculateSRSTab.Title = 'Calculate SRS';
            app.PlotButton = uibutton(app.CalculateSRSTab, 'push');
            app.PlotButton.Text = 'Plot';
            app.CalculateButton = uibutton(app.CalculateSRSTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.CalculateSRSTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.CalculateSRSTab, 'text');
            app.AmplificationFactorQEditField.Value = '10';
            app.StartFreqHzEditFieldLabel = uilabel(app.CalculateSRSTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.CalculateSRSTab, 'text');
            app.EndFreqHzEditFieldLabel = uilabel(app.CalculateSRSTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.CalculateSRSTab, 'text');
            app.GroundMotionDurationsecEditFieldLabel = uilabel(app.CalculateSRSTab);
            app.GroundMotionDurationsecEditFieldLabel.Text = 'Ground  Motion Duration (sec)';
            app.GroundMotionDurationsecEditField = uieditfield(app.CalculateSRSTab, 'text');
            app.OutputNameEditFieldLabel = uilabel(app.CalculateSRSTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.CalculateSRSTab, 'text');
            app.OutputNameEditField.Value = 'quake_srs';
            app.ReturnButton = uibutton(app.RVTFASSRSUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.RandomVibrationTheoryLabel_2 = uilabel(app.RVTFASSRSUIFigure);
            app.RandomVibrationTheoryLabel_2.Text = 'The input array must have two columns:  Freq (Hz) & Fourier magnitude (G)';
            app.RandomVibrationTheoryLabel_3 = uilabel(app.RVTFASSRSUIFigure);
            app.RandomVibrationTheoryLabel_3.Text = 'The Fourier magnitude must be from a one-sided, full-amplitude, discrete Fourier transform.';
            app.RandomVibrationTheoryLabel_4 = uilabel(app.RVTFASSRSUIFigure);
            app.RandomVibrationTheoryLabel_4.Text = 'The method works best for mostly stationary, random vibration.   May not be suitable for transient pulses.';
            % Show the figure after all components are created
            app.RVTFASSRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RTV_FAS_SRS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RVTFASSRSUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RVTFASSRSUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RVTFASSRSUIFigure)
        end
    end
end