classdef SEA_diffuse_field_panel_response_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        diffusefieldpanelresponseUIFigure  matlab.ui.Figure
        LossFactorexcludesacousticradiationLabel  matlab.ui.control.Label
        UniformLossFactor               matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        InputTwoColumns                 matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        StressConcentrationFactorEditField  matlab.ui.control.EditField
        StressConcentrationFactorLabel  matlab.ui.control.Label
        GasMassDensitylbmft3EditField   matlab.ui.control.EditField
        GasMassDensitylbmft3Label       matlab.ui.control.Label
        GasSpeedofSoundftsecEditField   matlab.ui.control.EditField
        GasSpeedofSoundftsecLabel       matlab.ui.control.Label
        SurroundingGasListBox           matlab.ui.control.ListBox
        SurroundingGasListBoxLabel      matlab.ui.control.Label
        CenterFrequencyHzEditField      matlab.ui.control.EditField
        CenterFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PressuredBEditField             matlab.ui.control.EditField
        PressuredBEditFieldLabel        matlab.ui.control.Label
        PressureUnitListBox             matlab.ui.control.ListBox
        PressureUnitListBoxLabel        matlab.ui.control.Label
        AnalysisTypeListBox             matlab.ui.control.ListBox
        AnalysisTypeListBoxLabel        matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3Label          matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        ThicknessinEditField            matlab.ui.control.EditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        WidthinEditField                matlab.ui.control.EditField
        WidthinEditFieldLabel           matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        SelectUnitListBox               matlab.ui.control.ListBox
        SelectUnitListBoxLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FreelyHungTab                   matlab.ui.container.Tab
        ResultsFH_EditField             matlab.ui.control.NumericEditField
        ResultsFH_Label                 matlab.ui.control.Label
        ViewEquationsLabel_2            matlab.ui.control.Label
        VelocityButton                  matlab.ui.control.Button
        RadiationEfficiencyButton       matlab.ui.control.Button
        CalculateButton_FreelyHung      matlab.ui.control.Button
        BaffledTab                      matlab.ui.container.Tab
        AdddBMarginEditField            matlab.ui.control.EditField
        AdddBMarginEditFieldLabel       matlab.ui.control.Label
        BoundaryConditionsListBox       matlab.ui.control.ListBox
        BoundaryConditionsListBoxLabel  matlab.ui.control.Label
        ResultsBB_EditField             matlab.ui.control.NumericEditField
        ResultsBB_Label                 matlab.ui.control.Label
        ViewEquationsLabel              matlab.ui.control.Label
        VelocityButton_Baffled          matlab.ui.control.Button
        RadiationEfficiencyButton_Baffled  matlab.ui.control.Button
        CalculateButton_Baffled         matlab.ui.control.Button
        LimpNonResonantTab              matlab.ui.container.Tab
        ResultsSAV_Limp_EditField       matlab.ui.control.NumericEditField
        ResultsSAV_Limp_Label           matlab.ui.control.Label
        ViewEquationButton_Limp         matlab.ui.control.Button
        CalculateButton_Limp            matlab.ui.control.Button
        SEA_diffuse_field_panel_response_Appver10byTomIrvineRLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        iu    %
        ipu   %
        iband %
        mass_density  %
        mass_per_area %
        thick %
        em    %
        L    %
        W    %
        mass %
        CL   %
        mu   %
        area %
        THM  %
        fc   %
        fcr  %
        NL   %
        nstep  %
        c      %
        gas_md %
        rho_c  %
        dB     %
        dBadd  %
        bc     %
        mph      %
        rad_ref  %
        v        %  
        accel    %
        total_lf %
        mdvr     %
        lf     %
        ulf    %
    end
    
    methods (Access = private)
        
        function change(app)

            TabChange(app);

            results_off(app);

            Items = char(app.SelectUnitListBox.Items);
            im=app.SelectUnitListBox.Value;
            [app.iu]=find_index(Items,im);

            if(app.iu==1)
                app.LengthinEditFieldLabel.Text='Length (in)';
                app.WidthinEditFieldLabel.Text='Width (in)';    
                app.ThicknessinEditFieldLabel.Text='Thickness (in)'; 
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';
                app.MassDensitylbmin3Label.Text='Mass Density (lbm/in^3)';
                sss=sprintf('Result\nSpatial Average Velocity\n(in/sec) rms');
            else
                app.LengthinEditFieldLabel.Text='Length (m)';
                app.WidthinEditFieldLabel.Text='Width (m)';    
                app.ThicknessinEditFieldLabel.Text='Thickness (mm)';   
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.MassDensitylbmin3Label.Text='Mass Density (kg/m^3)';   
                sss=sprintf('Result\nSpatial Average Velocity\n(m/sec) rms');      
            end

            app.ResultsSAV_Limp_Label.Text=sss;
            app.ResultsFH_Label.Text=sss;             

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [imat]=find_index(Items,im);
                       
            %%%  
            
            [elastic_modulus,app.mass_density,poisson]=six_materials(app.iu,imat);

            if(imat<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',app.mass_density);
                    ss3=sprintf('%8.4g',poisson);
            else
                    ss1=' ';
                    ss2=' ';
                    ss3=' ';        
            end
             
            app.ElasticModuluspsiEditField.Value=ss1;
            app.MassDensitylbmin3EditField.Value=ss2;
            app.PoissonRatioEditField.Value=ss3;

        end
        
        function pressure_unit(app)

            results_off(app);

            Items = char(app.AnalysisTypeListBox.Items);
            im=app.AnalysisTypeListBox.Value;
            [app.iband]=find_index(Items,im);

            if(app.iband==1)
                app.PressuredBEditField.Visible='off';
                app.PressuredBEditFieldLabel.Visible='off';  
                app.CenterFrequencyHzEditField.Visible='off';
                app.CenterFrequencyHzEditFieldLabel.Visible='off';

                app.InputArrayNameEditField.Visible='on';
                app.InputArrayNameEditFieldLabel.Visible='on'; 
             

                app.InputTwoColumns.Visible='on';
       
                sulf=sprintf('Uniform Loss Factor if the Input \n Array only has Two Columns');
            else
                app.PressuredBEditField.Visible='on';
                app.PressuredBEditFieldLabel.Visible='on';
                app.CenterFrequencyHzEditField.Visible='on';
                app.CenterFrequencyHzEditFieldLabel.Visible='on';   

                app.InputArrayNameEditField.Visible='off';
                app.InputArrayNameEditFieldLabel.Visible='off';                

                app.InputTwoColumns.Visible='off';  
            
                sulf=sprintf('Loss Factor');          
            end

            app.EditFieldLabel.Text=sulf;

            Items = char(app.PressureUnitListBox.Items);
            im=app.PressureUnitListBox.Value;
            [app.ipu]=find_index(Items,im);
            
            if(app.ipu==1)
                app.PressuredBEditFieldLabel.Text='Pressure (dB)';
            else
                if(app.iu==1)
                    app.PressuredBEditFieldLabel.Text='Pressure (psi rms)';
                else
                    app.PressuredBEditFieldLabel.Text='Pressure (Pa rms)';                
                end
            end
        end
        
        function results_off(app)

            ClosePlots(app);

            app.ResultsSAV_Limp_EditField.Visible='off';
            app.ResultsSAV_Limp_Label.Visible='off';
            app.ResultsFH_EditField.Visible='off';
            app.ResultsFH_Label.Visible='off';  
            app.ResultsBB_EditField.Visible='off';
            app.ResultsBB_Label.Visible='off';            
        end
        
        function getdata(app)
            app.thick=str2num(app.ThicknessinEditField.Value);

            if(isempty(app.thick))
                warndlg('Enter Thickness');
                return;
            end

            app.mass_density=str2num(app.MassDensitylbmin3EditField.Value);

            if(isempty(app.mass_density))
                warndlg('Enter density');
                return;
            end        
            
            if(app.iu==1)
                app.mass_density=app.mass_density/386;
            else
                app.thick=app.thick/1000;
            end

            app.mass_per_area=app.mass_density*app.thick;

            app.em=str2num(app.ElasticModuluspsiEditField.Value);

            if(isempty(app.em))
                warndlg('Enter elastic modulus');
                return;
            end

            app.mu=str2num(app.PoissonRatioEditField.Value);

            if(isempty(app.mu))
                warndlg('Enter Poisson ratio');
                return;
            end

            if(app.iu==2)
                [app.em]=GPa_to_Pa(app.em);
            end

            app.L=str2num(app.LengthinEditField.Value);
            app.W=str2num(app.WidthinEditField.Value);  

            if(isempty(app.L))
                warndlg('Enter Length');
                return;
            end            
            if(isempty(app.W))
                warndlg('Enter Width');
                return;
            end    
            
            app.area=app.L*app.W;

            app.mass=app.mass_per_area*app.area;
            
            [app.CL]=CL_plate(app.em,app.mass_density,app.mu);

        end
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
        
        function Gas(app)
            Items = char(app.SurroundingGasListBox.Items);
            im=app.SurroundingGasListBox.Value;
            [n]=find_index(Items,im);    

            if(app.iu==1)
                if(n==1)
                    app.GasSpeedofSoundftsecEditField.Value='1125';
                    app.GasMassDensitylbmft3EditField.Value='0.076487';
                else
                    app.GasSpeedofSoundftsecEditField.Value=' ';
                    app.GasMassDensitylbmft3EditField.Value=' ';
                end
            else
                if(n==1)
                    app.GasSpeedofSoundftsecEditField.Value='343';
                    app.GasMassDensitylbmft3EditField.Value='1.225';
                else
                    app.GasSpeedofSoundftsecEditField.Value=' ';
                    app.GasMassDensitylbmft3EditField.Value=' ';
                end            
            end
        end
        
        function read_data(app)

            try
                FS=strtrim(app.InputArrayNameEditField.Value);
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try again.')
                return;
            end                     

            app.fc=app.THM(:,1);
            app.NL=length(app.fc);  

            sz=size(app.THM);

            fprintf('\n\n Input array:  %d x %d \n\n',sz(1),sz(2));

                fmin=min(app.fc);
                fmax=max(app.fc);
                
                noct=log2(fmax/fmin);
                step=noct/(app.NL-1);
                
                app.nstep=0;

                if(step>0.30 && step < 0.36)
                    app.nstep=13;
                end

        end
        
        function freely_hung_multi(app)       
            
                read_data(app);
                
                sz=size(app.THM);
    
                if(sz(2)==2)
                    app.THM(:,3)=app.ulf;
                end
    
                disp(' ');
                disp(' * * * ');
                disp('  ');
                
                app.lf=app.THM(:,3);
            
                if(app.ipu==1)
                    app.dB=app.THM(:,2);
            
                    [pressure]=convert_dB_pressure_alt(app.dB,app.iu);
                else
                    pressure=app.THM(:,2);
                end
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Thin Panel
                
                %    L = length
                %    W = width
                %    c = speed of sound
                %    fcr = critical frequency
                
                app.v=zeros(app.NL,1);
                app.accel=zeros(app.NL,1);
                app.rad_ref=zeros(app.NL,1);
            
                for i=1:app.NL
            
                    freq=app.fc(i);
                
                    [app.rad_ref(i)]=re_thin_plate_free(freq,app.fcr,app.L,app.W,app.c);
                
                    omega=2*pi*freq;
                
                    anum=sqrt(12)*pi*app.c^2;

                    aden=2*app.rho_c*app.thick*app.CL*app.mass_per_area*omega^2;
                
                    bden= 1 + (app.mass_per_area*omega*app.lf(i))/(2*app.rho_c*app.rad_ref(i));
                
                    v2=(pressure(i)^2)*(anum/aden)*(1 / bden);
                
                    app.v(i)=sqrt(v2);

                    app.accel(i)=2*pi*app.v(i);
                    
                    %    radiation_resistance=rho_c*Ap*app.rad_ref;
                    %    clf=radiation_resistance/(mass*tpi*freq);

                end

                post(app);
                
        end
        
        function critical_frequency(app)
            app.ulf=app.UniformLossFactor.Value;

            app.c=str2num(app.GasSpeedofSoundftsecEditField.Value);
            app.gas_md=str2num(app.GasMassDensitylbmft3EditField.Value);
                
            if(app.iu==1)
                app.c=app.c*12;
                
                [app.gas_md]=lbm_per_ft3_to_lbf_sec2_per_in4(app.gas_md);
              
            end
                
            app.rho_c=app.c*app.gas_md;
            
            display_gas_acoustic_impedance(app.iu,app.rho_c);            

            [app.fcr]=critical_frequency_thin_plate(app.c,app.thick,app.mu,app.mass_density,app.em);

            fprintf(' Critical Frequency = %8.4g Hz \n\n',app.fcr);
        end
        
        function baffled_multi(app)
                        
                tpi=2*pi;

                read_data(app);
                
                sz=size(app.THM);
    
                if(sz(2)==2)
                    app.THM(:,3)=app.ulf;
                end
    
                disp(' ');
                disp(' * * * ');
                disp('  ');
                
                app.lf=app.THM(:,3);
            
                if(app.ipu==1)
                    app.dB=app.THM(:,2);
            
                    [pressure]=convert_dB_pressure_alt(app.dB,app.iu);
                else
                    pressure=app.THM(:,2);
                end
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Thin Panel
                
                %    L = length
                %    W = width
                %    c = speed of sound
                %    fcr = critical frequency
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.mph=zeros(app.NL,1);
                app.rad_ref=zeros(app.NL,1);
                app.v=zeros(app.NL,1);
                app.accel=zeros(app.NL,1);
                app.total_lf=zeros(app.NL,1);
                app.mdvr=zeros(app.NL,1);
                
                for i=1:app.NL
   
                           
                    [app.rad_ref(i)]=re_thin_plate_bc_alt(app.fc(i),app.fcr,app.L,app.W,app.c,app.bc);
                    [app.mph(i),~,~]=panel_modal_density_bc(app.em,app.mu,app.mass_density,app.thick,app.L,app.W,app.fc(i),app.bc);
                    [app.v(i),app.total_lf(i)]=velocity_from_diffuse_pressure_alt(pressure(i),...
                                              app.c,app.gas_md,app.mass,app.lf(i),app.rad_ref(i),app.fc(i),app.mph(i),app.area); 
                                          
                    omega=tpi*app.fc(i);                         
                    app.accel(i)=omega*app.v(i);        
                    app.mdvr(i)=app.total_lf(i)*app.mph(i)*app.fc(i);
                    
                end                    

               post(app);
               
        end
        
        function post(app)

            fig_num=1;

            scf=str2num(app.StressConcentrationFactorEditField.Value);
                
                K=2;
                strain=(K/app.CL)*app.v;
                stress=app.em*strain*scf;
                
                stress=fix_size(stress);
                
                if(app.iu==2)
                    app.v=app.v*1000;
                end
                
                fprintf(' Stress Concentration Factor = %g \n\n',scf);
                
                disp(' Stress is Free Field ');
                disp(' ');
                
                disp(' ');
                    disp(' Freq     Velocity    Stress      Radiation');
                if(app.iu==1)
                    disp(' (Hz)   (in/sec rms)  (psi rms)   Efficiency');
                else
                    disp(' (Hz)   (mm/sec rms)  (Pa rms)    Efficiency');
                end
            
                app.accel=zeros(app.NL,1);
                
                for i=1:app.NL
                   fprintf(' %g    %8.4g    %8.4g    %7.3g \n',app.fc(i),app.v(i),stress(i),app.rad_ref(i));
                   app.accel(i)=app.v(i)*(2*pi*app.fc(i));
                end
                
                if(app.iu==1)
                    app.accel=app.accel/386;
                else
                    app.accel=app.accel/9810;
                end
            
                arms=sqrt(sum(app.accel.^2));
                vrms=sqrt(sum(app.v.^2));
                prms=sqrt(sum(stress.^2));
                
                fprintf('\n Overall Acceleration = %7.3g GRMS \n\n',arms);
                
                if(app.iu==1)
                    out1=sprintf(' Overall Velocity = %7.3g in/sec RMS',vrms);
                else
                    out1=sprintf(' Overall Velocity = %7.3g mm/sec RMS',vrms);
                end
                disp(out1);
                
                if(app.iu==1)
                    out1=sprintf(' Overall Stress   = %7.3g psi RMS',prms);
                else
                    out1=sprintf(' Overall Stress   = %7.3g Pa RMS',prms);
                end
                disp(out1);
            
                %
                %  plots
                %
                ngg=2;
                md=4;
                fig_num=1;
                fmin=min(app.fc);
                fmax=max(app.fc);
                x_label='Frequency (Hz)';

                if(app.ipu==1) % dB
                
                    if(app.nstep==13)
                        [fig_num]=spl_plot_bar(fig_num,app.fc,app.dB);
                    end   
            
                else
            
                    t_string='Acoustic Pressure';
                    if(app.iu==1)
                        y_label='Pressure (psi rms)';
                    else
                        y_label='Pressure (Pa rms)';
                    end
                    ppp=[app.fc app.THM(:,2)];
    
                    if(app.nstep~=13)
    
                        [fig_num,~]=...
                        plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    else
                        [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                    end
    
                end
            
                % 

                t_string='Panel Loss Factor';
                y_label='Loss Factor';
                ppp=[app.fc app.THM(:,3)];
    
                if(app.nstep~=13)
                [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end

                t_string=sprintf('Panel Acceleration   %7.3g GRMS',arms);
                y_label='Accel (GRMS)';
           
                ppp=[app.fc app.accel];

                if(app.nstep~=13)
                [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end

                if(app.nstep==13 && app.iu==1)

                    nb=1;
            
                    [psd,~]=psd_from_spectrum(nb,app.fc,app.accel);
                
                    disp(' ');
                    disp(' Power Spectral Density ');
                    disp(' ');
                    disp('   Freq    Accel');
                    disp('   (Hz)   (G^2/Hz)');
                
                    for i=1:app.NL
                        fprintf('   %g   %8.4g \n',app.fc(i),psd(i));
                    end
                
                    accel_psd=[app.fc psd];
                
                    rrr1=accel_psd;
                
                    y_label='Accel (G^2/Hz)';
                
                    t_string=sprintf('Power Spectral Density  %7.3g GRMS Overall',arms);
                
                    [fig_num,~]=plot_loglog_function_md_h2(fig_num,x_label,...
                               y_label,t_string,rrr1,fmin,fmax,md);            

                end

                if(app.iu==1)
                    t_string=sprintf('Panel Velocity   %7.3g in/sec rms',vrms);
                    y_label='Vel (in/sec rms)';
                else                
                    t_string=sprintf('Panel Velocity   %7.3g mm/sec rms',vrms);
                    y_label='Vel (mm/sec rms)';
                end
                ppp=[app.fc app.v];
    
                if(app.nstep~=13)
                    [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end
    
                if(app.iu==1)
                    t_string=sprintf('Panel Stress   %7.4g psi rms',prms);
                    y_label='Stress (psi rms)';
                else
                    t_string=sprintf('Panel Stress   %7.4g Pa rms',prms);
                    y_label='Stress (Pa rms)';
                end
                ppp=[app.fc stress];

                if(app.nstep~=13)
                    [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end
                
                t_string='Radiation Efficiency';
                y_label='Radiation Efficiency';
                ppp=[app.fc app.rad_ref];
    
                if(app.nstep~=13)
                    [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end
            
                
                if(app.ipu~=1)
                   disp(' ');
                end
            
                accel_ps=[app.fc app.accel];
                vel_ps=[app.fc app.v];
                stress_ps=[app.fc stress];
                app.rad_ref=[app.fc app.rad_ref];
                
                assignin('base', 'vel_ps',vel_ps);
                assignin('base', 'stress_ps',stress_ps);
                assignin('base', 'rad_ref',app.rad_ref);

                try
                    accel_psd=[app.fc psd];        
                    assignin('base','accel_psd',accel_psd);
                catch
                end
                assignin('base','accel_ps',accel_ps);   


                disp(' Output Arrays ');
                disp('   Power Spectra:  vel_ps, accel_ps & stress_ps ');
                disp('   Power Spectral Density:  accel_psd')
                disp('   Radiation Efficiency:  app.rad_ref ');
                    
                msgbox('Results written to Command Window');            
         
        end
        
        function TabChange(app)
            if(contains(app.TabGroup.SelectedTab.Title,'Baffled'))
                app.LossFactorexcludesacousticradiationLabel.Visible='on';
            else
                app.LossFactorexcludesacousticradiationLabel.Visible='off';            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);
            pressure_unit(app);
            Gas(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_Limp
        function CalculateButton_LimpPushed(app, event)
            
            ClosePlots(app);
            getdata(app);
            
            if(app.iband==1)

                read_data(app);
                
                sz=size(app.THM);
                
                if(sz(2)~=2)
                    warndlg('Input file must have 2 columns ');
                    return;
                end
                            
                omega=2*pi*app.fc;

                if(app.ipu==1)
                    app.dB=app.THM(:,2);      
                        
                    [pressure]=convert_dB_pressure_alt(app.dB,app.iu);
                else
                    pressureapp.THM(:,2);
                end
                
                app.v=zeros(app.NL,1);
                
                for i=1:app.NL

                    if(i==1)
                        fprintf('pressure=%8.4g\n',pressure(i));
                    end    

                    v2=2*pressure(i)^2/(app.mass_per_area^2*omega(i)^2);
                    app.v(i)=sqrt(v2);    
                end
                
                if(app.iu==2)
                    app.v=app.v*1000;    
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                disp(' ');
                disp(' * * * ');
                disp('  ');
                
                disp(' ');
                disp('    Freq    Pressure   Velocity');
                
                if(app.iu==1)
                    disp('     (Hz)  (psi rms)  (in/sec) rms'); 
                else
                    disp('     (Hz)  (Pa rms)   (mm/sec) rms');    
                end
                
                for i=1:app.NL
                   fprintf(' %8.4g \t %8.3g \t %8.3g \n',app.fc(i),pressure(i),app.v(i));
                end
                
                vrms=sqrt(sum(app.v.^2));
                
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Overall Velocity = %7.3g in/sec RMS',vrms);
                else
                    out1=sprintf(' Overall Velocity = %7.3g mm/sec RMS',vrms);    
                end    
                disp(out1);
                
                vel_ps=[app.fc app.v];
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                ngg=2;

                md=4;
                fig_num=1;
                fmin=min(app.fc);
                fmax=max(app.fc);
                x_label='Frequency (Hz)';
                
                if(app.ipu==1) % dB
                
                    if(app.nstep==13)
                        [fig_num]=spl_plot_bar(fig_num,app.fc,app.dB);
                    end    
                
                else
                    
                    prms=sqrt(sum(app.THM(:,2).^2));

                    if(app.iu==1)
                        y_label='Pressure (psi)';
                        t_string=sprintf('Acoustic Pressure   %7.3g psi rms',prms);    
                    else
                        y_label='Pressure (Pa)';
                        t_string=sprintf('Acoustic Pressure   %7.3g Pa rms',prms);    
                    end   
                    ppp=[app.fc app.THM(:,2)];

                    if(app.nstep~=13)
                        
                        [fig_num,~]=...
                            plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                    else
                        [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                    end    
                end        
                
                
                if(app.iu==1)
                    y_label='Vel (in/sec)';
                    t_string=sprintf('Panel Velocity   %7.3g (in/sec) rms',vrms);   
                else
                    y_label='Vel (mm/sec)';    
                    t_string=sprintf('Panel Velocity   %7.3g (in/sec) rms',vrms);              
                end   
                ppp=vel_ps;


                if(app.nstep~=13)
                    [fig_num,~]=...
                        plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else

                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                disp(' ');
                disp(' Output Array ');
                disp('   Velocity power spectrum:  vel_ps ');
                
                vel_ps=[app.fc app.v];
                assignin('base', 'vel_ps',vel_ps);
                
                msgbox('Results written to Command Window');

            else

                app.fc=str2num(app.CenterFrequencyHzEditField.Value);

                if(isempty(app.fc))
                    warndlg('Enter Center Frequency');
                    return;
                end    

                pressure=str2num(app.PressuredBEditField.Value);
    
                if(app.ipu==1)    
                    [pressure]=convert_dB_pressure_alt(pressure,app.iu);
                end
    
                omega=2*pi*app.fc;

                v2=2*pressure^2/(app.mass_per_area^2*omega^2);

                app.v=sqrt(v2);

                if(app.iu==1)
                    ss=sprintf('%7.3g',app.v);
                else
                    ss=sprintf('%7.43',app.v*1000);    
                end
                
                app.ResultsSAV_Limp_EditField.Value=str2num(ss);

                app.ResultsSAV_Limp_EditField.Visible='on';
                app.ResultsSAV_Limp_Label.Visible='on';
            end
        end

        % Value changed function: SelectUnitListBox
        function SelectUnitListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            change(app);
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            A = imread('freely_hung_panel_diffuse_field.jpg');
            figure(997)
            imshow(A,'border','tight','InitialMagnification',100);
        end

        % Button pushed function: RadiationEfficiencyButton
        function RadiationEfficiencyButtonPushed(app, event)
            A = imread('re_free_panel.jpg');
            figure(998)
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: ViewEquationButton_Limp
        function ViewEquationButton_LimpPushed(app, event)
            A = imread('limp_panel.jpg');
            figure(999) 
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Value changed function: AnalysisTypeListBox
        function AnalysisTypeListBoxValueChanged(app, event)
            pressure_unit(app);
        end

        % Value changed function: PressureUnitListBox
        function PressureUnitListBoxValueChanged(app, event)
            pressure_unit(app);
        end

        % Value changing function: PressuredBEditField
        function PressuredBEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: CenterFrequencyHzEditField
        function CenterFrequencyHzEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: LengthinEditField
        function LengthinEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: WidthinEditField
        function WidthinEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: ThicknessinEditField
        function ThicknessinEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Button pushed function: CalculateButton_FreelyHung
        function CalculateButton_FreelyHungPushed(app, event)
            
            fprintf('\n\n * * * * * * * \n\n');

            ClosePlots(app);
            getdata(app);
            critical_frequency(app);

            tpi=2*pi;

            %%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.iband==1)

                freely_hung_multi(app);

            else
                app.fc=str2num(app.CenterFrequencyHzEditField.Value);

                if(isempty(app.fc))
                    warndlg('Enter Center Frequency');
                    return;
                end    

                pressure=str2num(app.PressuredBEditField.Value);
    
                if(app.ipu==1)    
                    [pressure]=convert_dB_pressure_alt(pressure,app.iu);
                end

                freq=app.fc;
                
                [app.rad_ref]=re_thin_plate_free(freq,app.fcr,app.L,app.W,app.c);
                
                omega=tpi*freq;
                
                anum=sqrt(12)*pi*app.c^2;
                aden=2*app.rho_c*app.thick*app.CL*app.mass_per_area*omega^2;
                
                bden= 1 + (app.mass_per_area*omega*app.ulf)/(2*app.rho_c*app.rad_ref);
                
                v2=(pressure^2)*(anum/aden)*(1 / bden);
                
                app.v=sqrt(v2);
      
                if(app.iu==1)
                    ss=sprintf('%7.3g',app.v);
                else
                    ss=sprintf('%7.43',app.v*1000);    
                end
                
                app.ResultsFH_EditField.Value=str2num(ss);

                app.ResultsFH_EditField.Visible='on';
                app.ResultsFH_Label.Visible='on';
            end    
        end

        % Value changed function: SurroundingGasListBox
        function SurroundingGasListBoxValueChanged(app, event)
            Gas(app);
        end

        % Button pushed function: CalculateButton_Baffled
        function CalculateButton_BaffledPushed(app, event)
           
            fprintf('\n\n * * * * * * * \n\n');

            ClosePlots(app);
            getdata(app);
            critical_frequency(app);

            app.dBadd=str2num(app.AdddBMarginEditField.Value); 

            Items = char(app.BoundaryConditionsListBox.Items);
            im=app.BoundaryConditionsListBox.Value;
            [app.bc]=find_index(Items,im);

            if(app.bc==2)
                app.bc=3;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.iband==1)

                baffled_multi(app);

            else
                app.fc=str2num(app.CenterFrequencyHzEditField.Value);

                if(isempty(app.fc))
                    warndlg('Enter Center Frequency');
                    return;
                end    

                pressure=str2num(app.PressuredBEditField.Value);
    
                if(app.ipu==1)    
                    [pressure]=convert_dB_pressure_alt(pressure,app.iu);
                end

                freq=app.fc;

                [app.rad_ref]=re_thin_plate_bc(freq,app.fcr,app.L,app.W,app.c,app.bc);
                
                [app.mph,~,~]=panel_modal_density_bc(app.em,app.mu,app.mass_density,app.thick,app.L,app.W,freq,app.bc);
                
                [app.v,~]=velocity_from_diffuse_pressure_alt(pressure,app.c,app.gas_md,app.mass,...
                                                                   app.ulf,app.rad_ref,freq,app.mph,app.area);

                fprintf('\n Radiation Efficiency = %8.4g \n',app.rad_ref);
                fprintf('       Modal density = %8.4g \n',app.mph);

                if(app.dBadd~=0)
                    app.v=app.v*10^(app.dBadd/20);
                end    

                if(app.iu==1)
                    ss=sprintf('%7.3g',app.v);
                else
                    ss=sprintf('%7.4g',app.v*1000);    
                end
                
                app.ResultsBB_EditField.Value=str2num(ss);

                app.ResultsBB_EditField.Visible='on';
                app.ResultsBB_Label.Visible='on';
            end    
        end

        % Button pushed function: RadiationEfficiencyButton_Baffled
        function RadiationEfficiencyButton_BaffledPushed(app, event)
            A = imread('re_thin_panel.jpg');
            figure(998)     
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: VelocityButton_Baffled
        function VelocityButton_BaffledPushed(app, event)
            A = imread('velocity_pressure_panel.jpg');
            figure(997) 
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Value changing function: AdddBMarginEditField
        function AdddBMarginEditFieldValueChanging(app, event)
            results_off(app);
        end

        % Value changed function: UniformLossFactor
        function UniformLossFactorValueChanged(app, event)
            results_off(app); 
        end

        % Callback function
        function UniformLossFactor_bValueChanged(app, event)
            results_off(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            TabChange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.diffusefieldpanelresponseUIFigure = uifigure('Visible', 'off');
            app.diffusefieldpanelresponseUIFigure.Name = 'diffuse field panel response';
            app.ReturnButton = uibutton(app.diffusefieldpanelresponseUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SEA_diffuse_field_panel_response_Appver10byTomIrvineRLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.SEA_diffuse_field_panel_response_Appver10byTomIrvineRLabel.Text = 'SEA_diffuse_field_panel_response_App     ver 1.0    by Tom Irvine          Response of a Panel to a diffuse acoustical field.';
            app.TabGroup = uitabgroup(app.diffusefieldpanelresponseUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FreelyHungTab = uitab(app.TabGroup);
            app.FreelyHungTab.Title = 'Freely Hung';
            app.CalculateButton_FreelyHung = uibutton(app.FreelyHungTab, 'push');
            app.CalculateButton_FreelyHung.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_FreelyHungPushed, true);
            app.CalculateButton_FreelyHung.Text = 'Calculate';
            app.RadiationEfficiencyButton = uibutton(app.FreelyHungTab, 'push');
            app.RadiationEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @RadiationEfficiencyButtonPushed, true);
            app.RadiationEfficiencyButton.Text = 'Radiation Efficiency';
            app.VelocityButton = uibutton(app.FreelyHungTab, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.ViewEquationsLabel_2 = uilabel(app.FreelyHungTab);
            app.ViewEquationsLabel_2.Text = 'View Equations';
            app.ResultsFH_Label = uilabel(app.FreelyHungTab);
            app.ResultsFH_Label.Text = {'Results '; 'Spatial Average Velocity '; '(in/sec) rms'};
            app.ResultsFH_EditField = uieditfield(app.FreelyHungTab, 'numeric');
            app.BaffledTab = uitab(app.TabGroup);
            app.BaffledTab.Title = 'Baffled';
            app.CalculateButton_Baffled = uibutton(app.BaffledTab, 'push');
            app.CalculateButton_Baffled.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_BaffledPushed, true);
            app.CalculateButton_Baffled.Text = 'Calculate';
            app.RadiationEfficiencyButton_Baffled = uibutton(app.BaffledTab, 'push');
            app.RadiationEfficiencyButton_Baffled.ButtonPushedFcn = createCallbackFcn(app, @RadiationEfficiencyButton_BaffledPushed, true);
            app.RadiationEfficiencyButton_Baffled.Text = 'Radiation Efficiency';
            app.VelocityButton_Baffled = uibutton(app.BaffledTab, 'push');
            app.VelocityButton_Baffled.ButtonPushedFcn = createCallbackFcn(app, @VelocityButton_BaffledPushed, true);
            app.VelocityButton_Baffled.Text = 'Velocity';
            app.ViewEquationsLabel = uilabel(app.BaffledTab);
            app.ViewEquationsLabel.Text = 'View Equations';
            app.ResultsBB_Label = uilabel(app.BaffledTab);
            app.ResultsBB_Label.Text = {'Results '; 'Spatial Average Velocity '; '(in/sec) rms'};
            app.ResultsBB_EditField = uieditfield(app.BaffledTab, 'numeric');
            app.BoundaryConditionsListBoxLabel = uilabel(app.BaffledTab);
            app.BoundaryConditionsListBoxLabel.Text = 'Boundary Conditions';
            app.BoundaryConditionsListBox = uilistbox(app.BaffledTab);
            app.BoundaryConditionsListBox.Items = {'Simply Supported', 'Clamped'};
            app.BoundaryConditionsListBox.Value = 'Simply Supported';
            app.AdddBMarginEditFieldLabel = uilabel(app.BaffledTab);
            app.AdddBMarginEditFieldLabel.Text = 'Add dB Margin';
            app.AdddBMarginEditField = uieditfield(app.BaffledTab, 'text');
            app.AdddBMarginEditField.ValueChangingFcn = createCallbackFcn(app, @AdddBMarginEditFieldValueChanging, true);
            app.AdddBMarginEditField.Value = '0';
            app.LimpNonResonantTab = uitab(app.TabGroup);
            app.LimpNonResonantTab.Title = 'Limp, Non-Resonant';
            app.CalculateButton_Limp = uibutton(app.LimpNonResonantTab, 'push');
            app.CalculateButton_Limp.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_LimpPushed, true);
            app.CalculateButton_Limp.Text = 'Calculate';
            app.ViewEquationButton_Limp = uibutton(app.LimpNonResonantTab, 'push');
            app.ViewEquationButton_Limp.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_LimpPushed, true);
            app.ViewEquationButton_Limp.Text = 'View Equation';
            app.ResultsSAV_Limp_Label = uilabel(app.LimpNonResonantTab);
            app.ResultsSAV_Limp_Label.Text = {'Results '; 'Spatial Average Velocity '; '(in/sec) rms'};
            app.ResultsSAV_Limp_EditField = uieditfield(app.LimpNonResonantTab, 'numeric');
            app.SelectUnitListBoxLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.SelectUnitListBoxLabel.Text = 'Select Unit';
            app.SelectUnitListBox = uilistbox(app.diffusefieldpanelresponseUIFigure);
            app.SelectUnitListBox.Items = {'English', 'Metric'};
            app.SelectUnitListBox.ValueChangedFcn = createCallbackFcn(app, @SelectUnitListBoxValueChanged, true);
            app.SelectUnitListBox.Value = 'English';
            app.LengthinEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.LengthinEditField.ValueChangingFcn = createCallbackFcn(app, @LengthinEditFieldValueChanging, true);
            app.WidthinEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.WidthinEditField.ValueChangingFcn = createCallbackFcn(app, @WidthinEditFieldValueChanging, true);
            app.ThicknessinEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.ThicknessinEditField.ValueChangingFcn = createCallbackFcn(app, @ThicknessinEditFieldValueChanging, true);
            app.MaterialListBoxLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.diffusefieldpanelresponseUIFigure);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.MassDensitylbmin3Label = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.MassDensitylbmin3Label.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.AnalysisTypeListBoxLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.AnalysisTypeListBoxLabel.Text = 'Analysis Type';
            app.AnalysisTypeListBox = uilistbox(app.diffusefieldpanelresponseUIFigure);
            app.AnalysisTypeListBox.Items = {'Multiple Bands', 'Single Band'};
            app.AnalysisTypeListBox.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeListBoxValueChanged, true);
            app.AnalysisTypeListBox.Value = 'Multiple Bands';
            app.PressureUnitListBoxLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.PressureUnitListBoxLabel.Text = 'Pressure Unit';
            app.PressureUnitListBox = uilistbox(app.diffusefieldpanelresponseUIFigure);
            app.PressureUnitListBox.Items = {'dB ref 20 micro Pa', 'psi rms'};
            app.PressureUnitListBox.ValueChangedFcn = createCallbackFcn(app, @PressureUnitListBoxValueChanged, true);
            app.PressureUnitListBox.Value = 'dB ref 20 micro Pa';
            app.PressuredBEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.PressuredBEditFieldLabel.Text = 'Pressure (dB)';
            app.PressuredBEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.PressuredBEditField.ValueChangingFcn = createCallbackFcn(app, @PressuredBEditFieldValueChanging, true);
            app.CenterFrequencyHzEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.CenterFrequencyHzEditFieldLabel.Text = 'Center Frequency (Hz)';
            app.CenterFrequencyHzEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.CenterFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @CenterFrequencyHzEditFieldValueChanging, true);
            app.SurroundingGasListBoxLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.SurroundingGasListBoxLabel.Text = 'Surrounding Gas';
            app.SurroundingGasListBox = uilistbox(app.diffusefieldpanelresponseUIFigure);
            app.SurroundingGasListBox.Items = {'Air', 'Other'};
            app.SurroundingGasListBox.ValueChangedFcn = createCallbackFcn(app, @SurroundingGasListBoxValueChanged, true);
            app.SurroundingGasListBox.Value = 'Air';
            app.GasSpeedofSoundftsecLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.GasSpeedofSoundftsecLabel.Text = {'Gas Speed of Sound '; '(ft/sec)'};
            app.GasSpeedofSoundftsecEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.GasMassDensitylbmft3Label = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.GasMassDensitylbmft3Label.Text = {'Gas Mass Density '; '(lbm/ft^3)'};
            app.GasMassDensitylbmft3EditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.StressConcentrationFactorLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.StressConcentrationFactorLabel.Text = {'Stress '; 'Concentration '; 'Factor'};
            app.StressConcentrationFactorEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.StressConcentrationFactorEditField.Value = '1';
            app.InputArrayNameEditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.diffusefieldpanelresponseUIFigure, 'text');
            app.InputTwoColumns = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.InputTwoColumns.Tag = 'text17';
            app.InputTwoColumns.Text = {'The input array must have at least two columns:'; '   '; '  1.  Center Frequency (Hz)     '; '  2.  Pressure   '; '  3.  Total Loss Factor (optional)'};
            app.EditFieldLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.EditFieldLabel.Text = {'Uniform Loss Factor if the Input '; 'Array only has Two Columns'};
            app.UniformLossFactor = uieditfield(app.diffusefieldpanelresponseUIFigure, 'numeric');
            app.UniformLossFactor.ValueChangedFcn = createCallbackFcn(app, @UniformLossFactorValueChanged, true);
            app.UniformLossFactor.Value = 0.01;
            app.LossFactorexcludesacousticradiationLabel = uilabel(app.diffusefieldpanelresponseUIFigure);
            app.LossFactorexcludesacousticradiationLabel.Text = {'Loss Factor excludes '; 'acoustic radiation'};
            % Show the figure after all components are created
            app.diffusefieldpanelresponseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEA_diffuse_field_panel_response_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.diffusefieldpanelresponseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.diffusefieldpanelresponseUIFigure)
        end
    end
end