classdef SEA_equivalent_acoustic_power_free_multi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        uipanel5                  matlab.ui.container.Panel
        pushbutton_power          matlab.ui.control.Button
        pushbutton_modal_density  matlab.ui.control.Button
        pushbutton_eq             matlab.ui.control.Button
        text58                    matlab.ui.control.Label
        text49                    matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        pushbutton_return         matlab.ui.control.Button
        pushbutton_calculate      matlab.ui.control.Button
        text59                    matlab.ui.control.Label
        edit_input_array          matlab.ui.control.EditField
        text56                    matlab.ui.control.Label
        edit_gas_md               matlab.ui.control.EditField
        text_gas_md               matlab.ui.control.Label
        text_W                    matlab.ui.control.Label
        text_L                    matlab.ui.control.Label
        edit_W                    matlab.ui.control.EditField
        edit_L                    matlab.ui.control.EditField
        listbox_gas               matlab.ui.control.ListBox
        text42                    matlab.ui.control.Label
        text_thick                matlab.ui.control.Label
        edit_thick                matlab.ui.control.EditField
        edit_c                    matlab.ui.control.EditField
        text_gas_c                matlab.ui.control.Label
        text3                     matlab.ui.control.Label
        listbox_units             matlab.ui.control.ListBox
        text_mu                   matlab.ui.control.Label
        edit_mu                   matlab.ui.control.EditField
        edit_md                   matlab.ui.control.EditField
        text_md                   matlab.ui.control.Label
        edit_em                   matlab.ui.control.EditField
        text_em                   matlab.ui.control.Label
        listbox_material          matlab.ui.control.ListBox
        text_material             matlab.ui.control.Label
        text2                     matlab.ui.control.Label
    end

    
    methods (Access = private)
        function change(app, hObject, eventdata, handles)
            %
            iu=get(handles.listbox_units,'Value');
            
            imat=get(handles.listbox_material,'Value');
            
            
            %%%
            
            ng=get(handles.listbox_gas,'Value');
            
            if(iu==1)
            
                    set(handles.text_gas_md,'String','Gas Mass Density (lbm/ft^3)');
                    set(handles.text_gas_c,'String','Gas Speed of Sound (ft/sec)');
            
                    if(ng==1)
                        set(handles.edit_c,'String','1125');
                        set(handles.edit_gas_md,'String','0.076487');
                    else
                        set(handles.edit_c,'String',' ');
                        set(handles.edit_gas_md,'String',' ');
                    end
            
            else
            
                    set(handles.text_gas_md,'String','Gas Mass Density (kg/m^3)');
                    set(handles.text_gas_c,'String','Gas Speed of Sound (m/sec)');
            
                    if(ng==1)
                        set(handles.edit_c,'String','343');
                        set(handles.edit_gas_md,'String','1.225');
                    else
                        set(handles.edit_c,'String',' ');
                        set(handles.edit_gas_md,'String',' ');
                    end
            
             end
            
            %%%
            
            set(handles.text_material,'String','Select Material');
            
            %%%
            
            if(iu==1)  % English
            
                set(handles.text_em,'String','Elastic Modulus (psi)');
                set(handles.text_md,'String','Mass Density (lbm/in^3)');
                set(handles.text_thick,'String','Thickness (in)');
                set(handles.text_L,'String','Length (in)');
                set(handles.text_W,'String','Width (in)');
                set(handles.text_gas_c,'String','Gas Speed of Sound (ft/sec)');
            else
                set(handles.text_em,'String','Elastic Modulus (GPa)');
                set(handles.text_md,'String','Mass Density (kg/m^3)');
                set(handles.text_thick,'String','Thickness (mm)');
                set(handles.text_L,'String','Length (m)');
                set(handles.text_W,'String','Width (m)');
                set(handles.text_gas_c,'String','Gas Speed of Sound (m/sec)');
            end
            
            %%%%%%%%%%%%%%
            
            [elastic_modulus,mass_density,poisson]=six_materials(iu,imat);
            
            %%%%
            
            if(imat<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
                    ss3=sprintf('%8.4g',poisson);
            else
                    ss1=' ';
                    ss2=' ';
                    ss3=' ';
            end
            
            set(handles.edit_em,'String',ss1);
            set(handles.edit_md,'String',ss2);
            set(handles.edit_mu,'String',ss3);
        end
        
        function edit_D11_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_D11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_D12_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_D12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_D22_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_D22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_D66_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_D66 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_H_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_H (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_I_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_I (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_Ix_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_Ix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_Iy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_Iy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_area_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_area (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_core_G_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_core_G (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_em_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_em_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_fcr_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_fcr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_fct_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_fct (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_freq_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_freq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_h_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_h (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_md_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_md_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_md_core_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_md_core (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_mu_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_mu_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_pressure_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_pressure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_r1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_r1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_r2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_r2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_thick_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_thick_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_velox_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_velox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_width_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_width (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function intermediate_print(app, hObject, eventdata, handles)
            %
                iu=getappdata(0,'iu');
                ns=getappdata(0,'ns');
                nc=getappdata(0,'nc');
                imat=getappdata(0,'imat');
            
                if(imat==1)
                    disp('Aluminum');
                end
                if(imat==2)
                    disp('Steel');
                end
                if(imat==3)
                    disp('Copper');
                end
                if(imat==4)
                    disp('G10');
                end
                if(imat==5)
                    disp('Other Material');
                end
        end
        
        function listbox8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_structure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_band_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_band (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_cross_Callback(app, hObject, eventdata, handles)
            % hObject    handle to listbox_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_cross contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_cross
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_cross_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_material_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_material_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_pu_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_pu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_structure_Callback(app, hObject, eventdata, handles)
            % hObject    handle to listbox_structure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_structure contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_structure
            change(app, hObject, eventdata, handles);
        end
        
        function listbox_structure_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_structure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SEA_equivalent_acoustic_power_free_multi_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SEA_equivalent_acoustic_power_free_multi (see VARARGIN)
            
            % Choose default command line output for SEA_equivalent_acoustic_power_free_multi
            handles.output = hObject;
            
            change(app, hObject, eventdata, handles);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_gas
        function listbox_gas_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_gas (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_gas contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_gas
            change(app, hObject, eventdata, handles);
        end

        % Value changed function: listbox_material
        function listbox_material_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_material (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_material contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_material
            change(app, hObject, eventdata, handles);
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_units contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_units
            change(app, hObject, eventdata, handles);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end
            
            disp(' ');
            
            iu=get(handles.listbox_units,'Value');
            
            setappdata(0,'iu',iu);
            
            
            %%%%%%
            
            try
                FS=get(handles.edit_input_array,'String');
                THM=evalin('base',FS);
            
            catch
                warndlg('Input Array does not exist.  Try again.')
                return;
            end
            
            sz=size(THM);
            
            if(sz(2)~=2)
                warndlg('Input file must have 2 columns ');
                return;
            end
            
            %%%%%%
            
            fc=THM(:,1);
            dB=THM(:,2);
            
            fig_num=1;
            
            
            NL=length(fc);
            fmin=min(fc);
            fmax=max(fc);
            
            noct=log2(fmax/fmin);
            step=noct/(NL-1);
            
            nstep=0;
            if(step>0.30 && step < 0.36)
                nstep=13;
            end
            
            
            if(nstep~=13)
                n_type=1;
                tstring='Input';
            
                [fig_num]=spl_plot_title(fig_num,n_type,fc,dB,tstring);
            else
                [fig_num]=spl_plot_bar(fig_num,fc,dB);
            end
            
            
            pressure=zeros(NL,1);
            
            for i=1:NL
            
                [psi_rms,Pa_rms]=convert_dB_pressure(dB(i));
            
                if(iu==1)
                    pressure(i)=psi_rms;
                else
                    pressure(i)=Pa_rms;
                end
            
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            em=str2num(get(handles.edit_em,'String'));
            md=str2num(get(handles.edit_md,'String'));
            mu=str2num(get(handles.edit_mu,'String'));
            c=str2num(get(handles.edit_c,'String'));
            
            
            L=str2num(get(handles.edit_L,'String'));
            W=str2num(get(handles.edit_W,'String'));
            
            SL = get(handles.edit_L, 'String');
            if isempty(SL)
              warndlg('Enter Length');
              return;
            else
                L=str2num(SL);
            end
            
            SW = get(handles.edit_W, 'String');
            if isempty(SW)
              warndlg('Enter Width');
              return;
            else
                W=str2num(SW);
            end
            
            
            Sthick = get(handles.edit_thick, 'String');
            if isempty(Sthick)
              warndlg('Enter Thickness');
              return;
            else
                thick=str2num(Sthick);
            end
            
            rho_g=str2num(get(handles.edit_gas_md,'String'));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iu==1) % English
            
                c=c*12;
                md=md/386;
                rho_g=(rho_g/386)/12^3;
            
            else     % metric
            
                [em]=GPa_to_Pa(em);
                thick=thick/1000;
            
            end
            rho=md;
            
            [fcr]=critical_frequency_thin_plate(c,thick,mu,rho,em);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            P=zeros(NL,1);
            mph=zeros(NL,1);
            rad_eff=zeros(NL,1);
            
            
            for i=1:NL
            
                freq=fc(i);
            
                [mph(i),~,~]=panel_modal_density_bc(em,mu,md,thick,L,W,freq,2);  % free
            
                [rad_eff(i)]=re_thin_plate_free(freq,fcr,L,W,c);
            
                max_rad_eff=3.5;
            
                if(rad_eff(i) > max_rad_eff)
                    rad_eff(i)=max_rad_eff;
                end
            
                [P(i)]=equivalent_acoustic_power(mph(i),c,rad_eff(i),pressure(i),md,thick,freq);
            
            end
            
            disp(' ');
            disp(' * * * ');
            disp(' ');
            
            fprintf(' Critical Frequency = %7.4g Hz \n\n',fcr);
            
            disp(' Input Power from Diffuse Acoustic Field into Panel ');
            disp(' ');
            
                disp('  Center     Modal  ');
                disp(' Frequency  Density     Radiation       Power ');
            
            if(iu==1)
                disp('    (Hz)   (modes/Hz)   Efficiency    (in-lbf/sec) ');
            else
                disp('    (Hz)   (modes/Hz)   Efficiency        (W) ');
            end
            
            for i=1:NL
               fprintf('     %g   %8.4g     %8.4g    %8.4g\n',fc(i),mph(i),rad_eff(i),P(i));
            end
            
            power=[fc P];
            mdens=[fc mph];
            rad_eff=[fc rad_eff];
            
            assignin('base', 'power',power);
            assignin('base', 'modal_density',mdens);
            assignin('base', 'rad_eff',rad_eff);
            
            disp(' ');
            disp('Output arrays ');
            disp('          Input Power: power ');
            disp('        Modal Density: modal_density ');
            disp(' Radiation Efficiency: rad_eff ');
            disp(' ');
            
            %%%%%%%%%%%%%%%%%%%%%
            
            x_label='Center Frequency (Hz)';
            md=3;
            
            
            ppp1=power;
            Wrms=sqrt(sum(ppp1(:,2).^2));
            
            
            if(iu==1)
                t_string=sprintf('Acoustic Power Spectrum   %7.3g in-lbf/sec overall',Wrms);
                y_label='Power (in-lbf/sec)';
            else
                t_string=sprintf('Acoustic Power Spectrum   %7.3g W overall',Wrms);
                y_label='Power (W)';
            end
            
            ng=2;
            
            % [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
            %               y_label,t_string,ppp1,fmin,fmax,md);
            
            [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            
            if(iu==1)
            
                ppp1(:,2)=ppp1(:,2)*0.113;
            
                Wrms=sqrt(sum(ppp1(:,2).^2));
            
                t_string=sprintf('Acoustic Power Spectrum   %7.3g W overall',Wrms);
            
                y_label='Power (W)';
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            end
            
            ppp1=mdens;
            t_string='Thin Panel, Free, Modal Density';
            y_label='n (modes/Hz)';
            
            
            % [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
            %               y_label,t_string,ppp1,fmin,fmax,md);
            
            [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            
            ppp1=rad_eff;
            y_label='Rad Eff';
            t_string='Thin Panel, Free, Radiation Efficiency';
            
            
            % [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
            %               y_label,t_string,ppp1,fmin,fmax,md);
            
            [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            
            %%%%%%%%%%%%%%%%%%%%%
            
            msgbox('Results written to command window');
        end

        % Button pushed function: pushbutton_eq
        function pushbutton_eq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_eq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            A = imread('re_free_panel.jpg');
            figure(997),imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: pushbutton_modal_density
        function pushbutton_modal_density_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_modal_density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            A = imread('Plate_bending_md.jpg');
            figure(998)
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: pushbutton_power
        function pushbutton_power_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_power (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            A = imread('equivalent_acoustic_power.jpg');
            figure(999)
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SEA_equivalent_acoustic_power_free_multi';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'SEA_equivalent_acoustic_power_free_multi.m  ver 1.3  by Tom Irvine';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Enter Data';
            app.uipanel2.Tag = 'uipanel2';
            app.text_material = uilabel(app.uipanel2);
            app.text_material.Tag = 'text_material';
            app.text_material.Text = 'Select Material';
            app.listbox_material = uilistbox(app.uipanel2);
            app.listbox_material.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.listbox_material.ValueChangedFcn = createCallbackFcn(app, @listbox_material_Callback, true);
            app.listbox_material.Tag = 'listbox_material';
            app.listbox_material.Value = 'Aluminum';
            app.text_em = uilabel(app.uipanel2);
            app.text_em.Tag = 'text_em';
            app.text_em.Text = 'Elastic Modulus (psi)';
            app.edit_em = uieditfield(app.uipanel2, 'text');
            app.edit_em.Tag = 'edit_em';
            app.text_md = uilabel(app.uipanel2);
            app.text_md.Tag = 'text_md';
            app.text_md.Text = 'Mass Density (lbm/in^3)';
            app.edit_md = uieditfield(app.uipanel2, 'text');
            app.edit_md.Tag = 'edit_md';
            app.edit_mu = uieditfield(app.uipanel2, 'text');
            app.edit_mu.Tag = 'edit_mu';
            app.text_mu = uilabel(app.uipanel2);
            app.text_mu.Tag = 'text_mu';
            app.text_mu.Text = 'Poisson Ratio';
            app.listbox_units = uilistbox(app.uipanel2);
            app.listbox_units.Items = {'English', 'Metric'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'English';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Units';
            app.text_gas_c = uilabel(app.uipanel2);
            app.text_gas_c.Tag = 'text_gas_c';
            app.text_gas_c.Text = 'Gas Speed of Sound (ft/sec)';
            app.edit_c = uieditfield(app.uipanel2, 'text');
            app.edit_c.Tag = 'edit_c';
            app.edit_thick = uieditfield(app.uipanel2, 'text');
            app.edit_thick.Tag = 'edit_thick';
            app.text_thick = uilabel(app.uipanel2);
            app.text_thick.Tag = 'text_thick';
            app.text_thick.Text = 'Thickness (in)';
            app.text42 = uilabel(app.uipanel2);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Select Surrounding Gas';
            app.listbox_gas = uilistbox(app.uipanel2);
            app.listbox_gas.Items = {'Air  ', 'Other'};
            app.listbox_gas.ValueChangedFcn = createCallbackFcn(app, @listbox_gas_Callback, true);
            app.listbox_gas.Tag = 'listbox_gas';
            app.listbox_gas.Value = 'Air  ';
            app.edit_L = uieditfield(app.uipanel2, 'text');
            app.edit_L.Tag = 'edit_L';
            app.edit_W = uieditfield(app.uipanel2, 'text');
            app.edit_W.Tag = 'edit_W';
            app.text_L = uilabel(app.uipanel2);
            app.text_L.Tag = 'text_L';
            app.text_L.Text = 'Length (in)';
            app.text_W = uilabel(app.uipanel2);
            app.text_W.Tag = 'text_W';
            app.text_W.Text = 'Width (in)';
            app.text_gas_md = uilabel(app.uipanel2);
            app.text_gas_md.Tag = 'text_gas_md';
            app.text_gas_md.Text = 'Gas Mass Density (lbm/ft^3)';
            app.edit_gas_md = uieditfield(app.uipanel2, 'text');
            app.edit_gas_md.Tag = 'edit_gas_md';
            app.text56 = uilabel(app.uipanel2);
            app.text56.Tag = 'text56';
            app.text56.Text = 'The input array must have two columns:';
            app.edit_input_array = uieditfield(app.uipanel2, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text59 = uilabel(app.uipanel2);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Center Freq (Hz) & SPL (dB)  ref 20 micro Pa';
            app.pushbutton_calculate = uibutton(app.uipanel2, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel2, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.text49 = uilabel(app.figure1);
            app.text49.Tag = 'text49';
            app.text49.Text = 'Thin panel, freely-suspended.';
            app.text58 = uilabel(app.figure1);
            app.text58.Tag = 'text58';
            app.text58.Text = 'The panel modal energy is much less than that of the acoustic space.';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'View Equations';
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton_eq = uibutton(app.uipanel5, 'push');
            app.pushbutton_eq.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_eq_Callback, true);
            app.pushbutton_eq.Tag = 'pushbutton_eq';
            app.pushbutton_eq.Text = 'Radiation Efficiency';
            app.pushbutton_modal_density = uibutton(app.uipanel5, 'push');
            app.pushbutton_modal_density.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_modal_density_Callback, true);
            app.pushbutton_modal_density.Tag = 'pushbutton_modal_density';
            app.pushbutton_modal_density.Text = 'Modal Density';
            app.pushbutton_power = uibutton(app.uipanel5, 'push');
            app.pushbutton_power.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_power_Callback, true);
            app.pushbutton_power.Tag = 'pushbutton_power';
            app.pushbutton_power.Text = 'Power Equation';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEA_equivalent_acoustic_power_free_multi_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SEA_equivalent_acoustic_power_free_multi_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end