classdef SEA_one_cylindrical_shell_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEAOneCylindricalShellUIFigure  matlab.ui.Figure
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisTypeGasTab              matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        AcousticFieldListBox            matlab.ui.control.ListBox
        AcousticFieldListBoxLabel       matlab.ui.control.Label
        SurroundingGasListBox           matlab.ui.control.ListBox
        SurroundingGasListBoxLabel      matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MaterialGeometryTab             matlab.ui.container.Tab
        BoundaryConditionsListBox       matlab.ui.control.ListBox
        BoundaryConditionsListBoxLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        UITable_geometry                matlab.ui.control.Table
        UITable_material                matlab.ui.control.Table
        InputOneThirdOctaveSPLTab       matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        UniformLossFactorEditField      matlab.ui.control.EditField
        UniformLossFactorEditFieldLabel  matlab.ui.control.Label
        InputFormatListBox              matlab.ui.control.ListBox
        InputFormatListBoxLabel         matlab.ui.control.Label
        ThelossfactorsareinternaldissipationLabel  matlab.ui.control.Label
        ZerodBref20microPaLabel         matlab.ui.control.Label
        CalculateTab                    matlab.ui.container.Tab
        ModalDensityMethodListBox       matlab.ui.control.ListBox
        ModalDensityMethodListBoxLabel  matlab.ui.control.Label
        SPLNameEditField                matlab.ui.control.EditField
        SPLNameEditFieldLabel           matlab.ui.control.Label
        PlotFrequenciesLabel            matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        UITable_plots                   matlab.ui.control.Table
        CylinderNameEditField           matlab.ui.control.EditField
        CylinderNameLabel               matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ViewEquationsTab                matlab.ui.container.Tab
        ViewButton                      matlab.ui.control.Button
        EquationListBox                 matlab.ui.control.ListBox
        EquationListBoxLabel            matlab.ui.control.Label
        SEA_one_cylindrical_shell_Appver26byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        ifield    %
        iformat   %
        igas      %
        imat      %
        imethod   %
        iu        %
        elastic_modulus  %
        mass_density     %
        poisson          %
        THM       %
    end
    
    methods (Access = private)

        function clear(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end
        end    

       function update(app)
            clear(app);

            Items = strtrim(app.InputFormatListBox.Items);
            im = strtrim(app.InputFormatListBox.Value);
            app.iformat = find(strcmp(Items, im));

            try
                if(app.iformat==1)
                    cell_array=update_spl_3();
                else
                    cell_array=update_spl();                
                end    
            catch
                msgbox('updata_spl found no files');
            end
            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
                app.listbox_method.Value=app.listbox_method.Items{1}; 
            catch
            end

            try
                ff = app.SelectInputArrayListBox.Value;
                app.SPLNameEditField.Value=ff;
            catch
            end

        end        

        function ChangeMaterials(app)

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            Items = strtrim(app.MaterialListBox.Items);
            im = strtrim(app.MaterialListBox.Value);
            app.imat = find(strcmp(Items, im));

            [app.elastic_modulus,app.mass_density,app.poisson]=six_materials(app.iu,app.imat);

            data_s = cell(3, 2);

            data_s{3,1}='Poisson ratio';
            data_s{3,2}=sprintf('%g',app.poisson);

            if(app.iu==1)
                data_s{1,1}='Elastic Modulus (psi)';
                data_s{2,1}='Mass Density (lbm/in^3)';
            else
                data_s{1,1}='Elastic Modulus (GPa)';
                data_s{2,1}='Mass Density (kg/m^3)';
            end  

            data_s{1,2}=sprintf('%g',app.elastic_modulus);
            data_s{2,2}=sprintf('%g',app.mass_density);

            app.UITable_material.Data=data_s;

        end    

        function SPLFormat(app)

            clear(app);

            Items = strtrim(app.InputFormatListBox.Items);
            im = strtrim(app.InputFormatListBox.Value);
            app.iformat = find(strcmp(Items, im));

            if(app.iformat==1)
                app.UniformLossFactorEditFieldLabel.Visible='off';
                app.UniformLossFactorEditField.Visible='off';
            else
                app.UniformLossFactorEditFieldLabel.Visible='on';
                app.UniformLossFactorEditField.Visible='on';           
            end    

        end    
        
        function change(app)

            clear(app);

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            Items = strtrim(app.SurroundingGasListBox.Items);
            im = strtrim(app.SurroundingGasListBox.Value);
            app.igas = find(strcmp(Items, im));

            Items = strtrim(app.AcousticFieldListBox.Items);
            im = strtrim(app.AcousticFieldListBox.Value);
            app.ifield = find(strcmp(Items, im));

            if(app.ifield==1)

                if(app.iu==1)
                    data_s{1,1}='Gas Speed of Sound (ft/sec)';
                    data_s{2,1}='Gas Mass Density (lbm/ft^3)';
                    data_s{1,2}='1125';
                    data_s{2,2}='0.076487';  
                else
                    data_s{1,1}='Gas Speed of Sound (m/sec)';
                    data_s{2,1}='Gas Mass Density (kg/m^3)'; 
                    data_s{1,2}='343';
                    data_s{2,2}='1.225'; 
                end  

            else
                 data_s{1,1}='Mach Number';

                 if(app.iu==1)
                    data_s{2,1}='Altitude (ft)';
                 else
                    data_s{2,1}='Altitude (km)';                
                 end

                 data_s{3,1}='Corcos Coefficient ax';
                 data_s{3,2}='0.116';

                 data_s{4,1}='Corcos Coefficient az';
                 data_s{4,2}='0.7';
            end    

            app.UITable.Data=data_s;     

            
            data_g = cell(3, 2);

            if(app.iu==1)
                data_g{1,1}='Length (in)';
                data_g{2,1}='Diameter (in)';
                data_g{3,1}='Thickness (in)';             
            else
                data_g{1,1}='Length (m)';
                data_g{2,1}='Diameter (m)';
                data_g{3,1}='Thickness (mm)';  
            end    

            app.UITable_geometry.Data=data_g;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);

            app.UITable_geometry.ColumnWidth={130,61};
            app.UITable_plots.ColumnWidth={225,60,60};

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.imethod]=find_index(Items,im);

            ChangeMaterials(app);
            SPLFormat(app);
            update(app);

            data_s = cell(10, 2);

            data_s{1,1}='Input Power Spectrum (W)';
            data_s{2,1}='Input Power Spectrum (dB)';
            data_s{3,1}='Input Power PSD';

            data_s{4,1}='Radiation Efficiency';
            data_s{5,1}='Modal Density (modes/Hz)';
            data_s{6,1}='Modal Density (modes/(rad/sec))';
            data_s{7,1}='Modal Overlap';
            data_s{8,1}='Number of Modes in Band';

            data_s{9,1}='Velocity Spectrum';
            data_s{10,1}='Accelation Spectrum';
            data_s{11,1}='Acceleration PSD';
            data_s{12,1}='Total Loss Factor';

            data_s{1,2}=false;
            data_s{2,2}=true;
            data_s{3,2}=false;

            data_s{4,2}=false;
            data_s{5,2}=false;
            data_s{6,2}=false;
            data_s{7,2}=false;
            data_s{8,2}=false;

            data_s{9,2}=false;
            data_s{10,2}=false;
            data_s{11,2}=true;
            data_s{12,2}=false;            

            data_s{1,2}=false;
            data_s{2,2}=true;
            data_s{3,2}=false;

            data_s{4,2}=true;
            data_s{5,2}=false;
            data_s{6,2}=false;
            data_s{7,2}=false;
            data_s{8,2}=false;

            data_s{9,2}=false;
            data_s{10,2}=false;
            data_s{11,2}=true;
            data_s{12,2}=false;

            data_s{1,3}=false;
            data_s{2,3}=true;
            data_s{3,3}=false;

            data_s{4,3}=true;
            data_s{5,3}=false;
            data_s{6,3}=false;
            data_s{7,3}=false;

            data_s{8,3}=false;
            data_s{9,3}=false;
            data_s{10,3}=false;
            data_s{11,3}=true;
            data_s{12,3}=false;

            app.UITable_plots.Data=data_s;
       
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            change(app);
            ChangeMaterials(app);
        end

        % Value changed function: SurroundingGasListBox
        function SurroundingGasListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: AcousticFieldListBox
        function AcousticFieldListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)


            ChangeMaterials(app);

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear(app);

            Items = strtrim(app.ModalDensityMethodListBox.Items);
            im = strtrim(app.ModalDensityMethodListBox.Value);
            imdens = find(strcmp(Items, im));

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            name=strtrim(app.CylinderNameEditField.Value);
            name=strrep(name,' ','_');

            tpi=2*pi;

            iuo=app.iu;

            PP=app.UITable_plots.Data; 

            fig_num=1;
             
            if(app.imethod==1)
                try
                    FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end

            try
                sz=size(app.THM);
            catch    
                warndlg('Input Array is empty');
                return;              
            end

            freq=app.THM(:,1);
            fc=freq;
            dB(:,1)=app.THM(:,2);

            lf=zeros(sz(2),1);

            if(sz(2)==2)
                lf(1:sz(1))=str2num(app.UniformLossFactorEditField.Value);
            else
                lf=app.THM(:,3);            
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fstart=str2num(app.MinEditField.Value);
            fend=str2num(app.MaxEditField.Value);          

            [~,meters_per_inch]=length_unit_conversion();
            [kgpm3_per_lbmpft3,kgpm3_per_lbmpin3]=mass_per_volume_unit_conversion();
            [~,Pa_per_psi]=pressure_unit_conversion2();

            B=char(app.UITable_material.Data);
            
            E=str2num(B(4,:));
            rho=str2num(B(5,:));
            mu=str2num(B(6,:));

            B=char(app.UITable_geometry.Data);
            
            L=str2num(B(4,:));
            diam=str2num(B(5,:));
            h=str2num(B(6,:));

            if(app.iu==1)  % convert English to metric
    
                rho=rho*kgpm3_per_lbmpin3;
                h=h*meters_per_inch;
                L=L*meters_per_inch;
                diam=diam*meters_per_inch;
                E=E*Pa_per_psi;
            else
                [E]=GPa_to_Pa(E);
                h=h/1000;
            end

            CL=sqrt(E/rho);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fs_velox=0;
            mach=0;

            B=char(app.UITable.Data);

            if(app.ifield==1) % Diffuse
                gas_c=str2num(B(3,:));
                gas_md=str2num(B(4,:));
            
            else    % TBL
                mach=str2num(B(5,:));
                altitude=str2num(B(6,:));
                ax=str2num(B(7,:));
                az=str2num(B(8,:));
            
                [~,mass_dens,~,~,sound_speed]=atmopheric_properties(altitude,app.iu);
                
                fs_velox=mach*sound_speed;
            
                gas_md=mass_dens;
                gas_c=sound_speed;
                
            end

            if(app.iu==1)  % convert English to metric
   
                gas_c=gas_c*12;
                gas_c=gas_c*meters_per_inch;
                gas_md=gas_md*kgpm3_per_lbmpft3;
   
            end

            if(app.ifield==2)
                fs_velox=mach*gas_c;  % m/sec
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.iu=2;
            
            c=gas_c;
            rho_c=gas_md*gas_c;
            
            num=length(fc);
            
            fl=zeros(num,1);
            fu=zeros(num,1);
            bw=zeros(num,1);
            
            for i=1:num
               fl(i)=fc(i)*(1/2^(1/6)); 
               fu(i)=fl(i)*2^(1/3);
               bw(i)=fu(i)-fl(i);
            end

            [D,K,mpa,fcr,fring,~]=cylinder_properties_wave(E,mu,h,diam,rho,gas_c);

            Ap=L*pi*diam;
            mass=mpa*Ap;
            m=mass;

            Items = strtrim(app.BoundaryConditionsListBox.Items);
            im = strtrim(app.BoundaryConditionsListBox.Value);
            bc = find(strcmp(Items, im));

            mmax=500;
            nmax=500;  

            [recn,mph_Heckl]=re_cylinder_engine_alt_2(mu,diam,L,h,mmax,nmax,gas_c,CL,fcr,fring,fl,fu);

            if(imdens==1)  % Heckl
                mph=mph_Heckl;
            end
            if(imdens==2)  % Szechenyi
                for i=1:num
                    [mph(i)] = cylinder_mdens(fc(i),fring,L,h);
                end    
            end     
            if(imdens==3)  % Bing-ru
                [~,mph]=re_cylinder_engine(D,K,mu,diam,L,mpa,bc,mmax,nmax,gas_c,fcr,fring,fc,fl,fu);
            end    

            mph=fix_size(mph);
            
            [BB]=flexural_rigidity(E,h,mu);
            Zp=8*sqrt(BB*rho*h);

            rad_eff=zeros(num,1);

            disp('ref 1')

            jring=0;

            for j=1:num 
 
                [~,~,~,Zc]=cylinder_mobility(E,rho,diam,L,h,fc(j)); 

                RT=13.8/(2*pi*fc(j)*lf(j));

                bb=2*pi*c^2*rho*h*RT;

                Z=max([Zp Zc]);

                rad_forced=13.8*Z/bb;

                if(fl(j)<=fring)
                    rec=recn(j);
                else    
                    [rec]=cylinder_rad_eff_alt(fc(j),fcr,fring,L,diam,c,bc);
                end

                if(fl(j)<=fring && fring<=fu(j))
                    jring=j;
                end    

                rad_eff(j)=rec+rad_forced;

                fprintf('  %8.4g  %8.4g  rec=%8.4g  recn=%8.4g  rad_forced%8.4g \n',fc(j),rad_eff(j),rec,recn(j),rad_forced);

            end

            nj=jring-2;

            for j=1:nj-1  % Fahy, Gardonio
                noct=log2(fc(nj)/fc(j));
                delta_dB=-noct*5;
                ratio=10^(delta_dB/10);
                rrr=rad_eff(nj)*ratio;
                rad_eff(j)=rrr;
            end


            fprintf('\n\n');

            jf=1;
            jg=1;

            for i=(num-2):-1:1
                if(mph(i)<1.e-20  || rad_eff(i)<1.e-20  || fc(i)<fstart ) 
                    jf=i+1;
                    % disp(' break 1');
                    break;
                end
            end

            for i=(num-2):-1:1
    
                if(mph(i)<1.e-20  || rad_eff(i)<1.e-20  )
                    jg=i+1;
                    % disp(' break 2');
                    break;
                end
            end

            % fprintf(' jf=%d jg=%d  num=%d \n',jf,jg,num);
     
            try
                fmin=fc(jf);
            catch
                fmin=fc(1);
            end
                    
            fmax=fc(num);          

            try
                fprintf('\n fc(jf)=%8.4g  jf=%d  fc(jg)=%8.4g  jg=%d  \n\n',fc(jf),jf,fc(jg),jg);
            catch
            end
                
            fprintf('\n fmin=%8.4g   fmax=%8.4g  \n\n',fmin,fmax);
            
            if(fmax<1)
                warndlg('fmax error');
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            n_type=1;
            
            if(app.ifield==1)
                [fig_num]=spl_plot_nd(fig_num,n_type,freq,dB(:,1));    
            else
                [fig_num]=fpl_plot_nd(fig_num,n_type,freq,dB(:,1));      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            kjv=1;
            
            x_label='Center Frequency (Hz)';
            y_label='Ratio';
            t_string='Radiation Efficiency';
            
            md=3;
            
            ppp=[fc(jg:num) rad_eff(jg:num,1)];

            if(PP{4,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);     
            end
            if(PP{4,3})
                nnn=sprintf('%s_rad_eff',name);
                assignin('base',nnn, ppp);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end          
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            alpha=2^(1/6)-1/2^(1/6);
            
            k=1;
            
            num_modes=zeros((num-jg+1),1);  
            
            fnm=zeros((num-jg+1),1);
            
            for i=jg:num
                bbw=fc(i)*alpha;
                fnm(k)=fc(i);
                num_modes(k)=mph(i)*bbw;
                k=k+1;
            end
            
            data=[fnm num_modes(:,1)];
            
            xlabel='Center Frequency (Hz)';
            ylabel='Modes';
            
            t_string=sprintf('Number of Modes in Band');
            
            if(PP{8,2})
                [fig_num]=plot_one_onethird_bar_f(fig_num,xlabel,ylabel,data,t_string,fmin,fmax); 
            end
            if(PP{8,3})
                nnn=sprintf('%s_modes_band',name);
                assignin('base',nnn, data);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end              
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp=[fc(jg:num) mph(jg:num,1)];
            
            x_label='Center Frequency (Hz)';
            y_label='n (modes/Hz)';
            t_string='Modal Density';
            
            md=3;
            
            if(PP{5,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fstart,fend,md); 
            end
            if(PP{5,3})
                nnn=sprintf('%s_modal_dens_Hz',name);
                assignin('base',nnn, ppp);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end               

            ppp_rps=[ppp(:,1) ppp(:,2)/tpi];

            y_label='n (modes/(rad/sec))';
            
            if(PP{6,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp_rps,fstart,fend,md); 
            end    
            if(PP{6,3})
                nnn=sprintf('%s_modal_dens_rad_per_sec',name);
                assignin('base',nnn, ppp_rps);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end  
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp('   Modal Density (modes/Hz)');
            disp(' ');
            disp('   fc(Hz)   mph   rad eff');
            disp(' ');
            
            for i=1:num    
                fprintf('%7.1f  %8.4g  %8.4g \n',fc(i),mph(i),rad_eff(i));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
               power=zeros(num,1);
            power_dB=zeros(num,1);
            
            
            if(app.ifield==2)
                Uc=0.8*fs_velox;
            end
            
            for i=1:num    
            
                if(app.ifield==1) % diffuse
                
                    [power(i),power_dB(i)]=power_from_spl_dB(fc(i),dB(i),mph(i),c,mpa,rad_eff(i));    
                      
                else       % TBL
                 
                    [power(i),power_dB(i)]=power_from_TBL_spl_dB(fc(i),dB(i),mpa,Ap,L,diam,Uc,ax,az,D);    
        
                end
                
            end

            disp(' ');
            disp(' Zero dB References: ');
            disp('   Pressure 20 micro Pa');
            disp('   Power     1 pico Watt ');
            disp(' ');
            
            if(app.ifield==1)
                disp('   fc    SPL  ');
            else
                disp('   fc    FPL  ');    
            end
                
            disp('  (Hz)   (dB)  ');
            
            for i=1:num
                fprintf('%7.1f  %6.1f \n',fc(i),dB(i,1));
            end    
            
            
            disp(' ');
            disp('   fc    Power ');
            disp('  (Hz)   (dB)   ');
            
            
            for i=1:num    
                fprintf('%7.1f  %6.1f \n',fc(i),power_dB(i,1));
            end
            
            disp(' ');
            disp(' Overall Power Level ');
            
            oapow=oaspl_function(power_dB);
            
            fprintf('  Power = %7.4g dB \n\n',oapow);
            
            power_ref=1.0e-12;
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            NL=length(fc);
            
            omega=tpi*fc;
            
            Moverlap1=zeros(NL,1);
            
            lfa=zeros(NL,1);

            for i=1:NL
            
                [Moverlap1(i)]=SEA_modal_overlap(mph(i),lf(i),freq(i));
                radiation_resistance=rho_c*Ap*rad_eff(i);
                lfa(i)=radiation_resistance/(m*omega(i));  
                
            end
                
            total_lf=lf+lfa;
            
            ppp=[fc(jg:num)  Moverlap1(jg:num) ];
            
            x_label='Center Frequency (Hz)';
            y_label=' ';
            t_string='Modal Overlap';
            
            md=4;
            
            if(PP{7,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fstart,fend,md);
            end
            if(PP{8,3})
                nnn=sprintf('%s_modal_overlap',name);
                assignin('base',nnn, ppp);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end  

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%      
            
            v=zeros(NL);
            a=zeros(NL);
            
            for i=1:NL
            
                A=total_lf(i,1);
                B=power(i)/omega(i);
                E=A\B;
                
                [v(i),a(i)]=energy_to_velox_accel(E,m,omega(i));    
                
            end
            
            if(app.iu==2)
               v=v*1000;  
            end
            
            if(app.iu==1)
               a=a/386; 
            else
               a=a/9.81;   
            end
            
            if(app.iu==1)
                seu='in-lbf';
                spu='in-lbf/sec';
                spv='in/sec';
            else
                seu='J';
                spu='W';
                spv='mm/sec';    
            end  
            
            if(iuo==1)
                v=v/25.4;
                spv='in/sec';
            end
            
            
            disp(' ');
            disp(' * * * * ');
            disp(' ');
            
            disp(' Freq    Accel');
            disp(' (Hz)    (GRMS)');    
            
            for i=1:NL
                fprintf(' %g   %8.4g \n',fc(i),a(i));
            end
            
            x_label='Frequency (Hz)';
            y_label=sprintf('Velocity (%s) rms',spv);
            
            md=5;
            
            ppp=[fc(jf:num) v(jf:num,1)];
            assignin('base', 'vel_ps_1',ppp);
            
            t_string='Velocity Spectrum';
            
            if(PP{9,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fstart,fend,md);
            end
            if(PP{9,3})
                nnn=sprintf('%s_vel_spectrum',name);
                assignin('base',nnn, ppp);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end  

            %%%
            
            ppp=[fc(jf:num) a(jf:num,1)];
            assignin('base', 'accel_ps_1',ppp); 
            
            y_label=sprintf('Accel (G rms)');           
            t_string='Acceleration Spectrum';
            
            if(PP{10,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fstart,fend,md);
            end     
            if(PP{10,3})
                nnn=sprintf('%s_accel_spectrum',name);
                assignin('base',nnn, ppp);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end             
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            va_eff_1=fc*diam;
            
            for i=1:num
                adB=20*log10(a(i)/1);
                va_eff_1(i,2)=adB+20*log10(mpa)-dB(i);     
            end
            
            NN=length(fc);
            
            for i=NN:-1:1
                if(i>=jf && i<=num)
                else
                     va_eff_1(i,:)=[];
                end
            end
                
            qqq_dB=[fc(jf:num) power_dB(jf:num,1)];
            
            
            qqq=[fc(jf:num) power(jf:num,1)];


            y_label='Power (W)';    
            
            [oapow]=oaspl_function(power_dB(jf:num));
                wov=power_ref*10^(oapow/10);
  
            
            t_string=sprintf('Input Power Spectrum  %8.4g W overall',wov);
               
            if(PP{1,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,qqq,fstart,fend,md); 
            end
            if(PP{1,3})
                nnn=sprintf('%s_power_Watts',name);
                assignin('base',nnn, qqq);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end  

            nb=1;

            [power_psd,~]=psd_from_spectrum(nb,qqq(:,1),qqq(:,2));


            if(PP{2,2})
                n_type=1;
                [fig_num]=sound_power_plot_nd(fig_num,n_type,qqq_dB(:,1),qqq_dB(:,2),fmin,fmax);
            end    
            if(PP{2,3})
                nnn=sprintf('%s_power_dB',name);
                assignin('base',nnn, qqq_dB);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end          

            y_label='Power (W^2/Hz)';
                
            t_string=sprintf('Power Spectral Density  %8.4g W overall',wov);

            qp_psd=[qqq(:,1) power_psd];
             
            if(PP{3,2})
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,qp_psd,fstart,fend,md);  
            end
            if(PP{3,3})
                nnn=sprintf('%s_power_psd',name);
                assignin('base',nnn, qp_psd);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end       
            qp=[fc power];
            
            NN=length(fc);
            
            for i=NN:-1:1
                if(i>=jf && i<=num)
                else
                     qp(i,:)=[];
                end
            end
            
                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            t_string='Total Loss Factor';
            y_label='Loss Factor'; 
            
            rrr=[fc total_lf(:,1)];
            
            if(PP{12,2})
                [fig_num,~]=plot_loglin_function_h2(fig_num,x_label,y_label,t_string,rrr,fstart,fend);
            end  
            if(PP{12,3})
                nnn=sprintf('%s_total_loss_factor',name);
                assignin('base',nnn, rrr);     
                output_name{kjv}=nnn;
                kjv=kjv+1;
            end                

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            nb=1;
            
            if(nb<=2)
                
                [psd,~]=psd_from_spectrum(nb,fc,a);    
                
                [~,grms] = calculate_PSD_slopes(fc(jf:num),psd(jf:num));
                 
                disp(' ');
                disp(' Power Spectral Density ');
                disp(' ');
                disp('   Freq    PSD    ');
                disp('   (Hz)   (G^2/Hz) ');
                       
                for i=jf:NL
                    fprintf('  %g    %8.4g \n',fc(i),psd(i));
                end
             
                disp(' ');
                disp(' Overall Levels ');
                disp(' ');   
                fprintf('  %8.4g GRMS \n',grms);
                 
                psd=[fc(jf:num) psd(jf:num,1)];
            
                qqq=psd;
            
                assignin('base', 'accel_psd_1',psd);
                
                y_label='Accel (G^2/Hz)';
                
                t_string=sprintf('Power Spectral Density  %7.3g GRMS',grms);
             
                if(PP{11,2})
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,qqq,fstart,fend,md);  
                end
                if(PP{11,3})
                    nnn=sprintf('%s_accel_psd',name);
                    assignin('base',nnn, qqq);     
                    output_name{kjv}=nnn;
                    kjv=kjv+1;
                end                   
            end
             
  
            disp(' ');
            fprintf('     Ring Frequency = %8.4g Hz \n',fring);
            fprintf(' Critical Frequency = %8.4g Hz \n\n',fcr);
            
            fprintf(' Mass per area = %8.4g kg/m^2 \n',mpa);
            fprintf('               = %8.4g lbm/ft^2 \n',mpa*0.20485 );
            fprintf('               = %8.4g lbm/in^2 \n',mpa*0.0014226 );

            % aa1=[fc dB rad_eff mph];
            
            if(kjv>=2)

                fprintf('\n Output arrays \n');

                for i=1:kjv-1
                    fprintf('   %s \n',output_name{i});
                end    

            end
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            msgbox('Results written to Command Window');

        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            
            Items = strtrim(app.EquationListBox.Items);
            im = strtrim(app.EquationListBox.Value);
            n = find(strcmp(Items, im));
            
            if(n==1)
                A = imread('power_velox_energy.jpg');
            end
            if(n==2)
                A = imread('clf_line_joint_plates.jpg');    
            end
            if(n==3)
                A = imread('equivalent_acoustic_power_cylinder.jpg');
            end
            if(n==4)
                A = imread('Corcos_TBL.jpg');
            end
            if(n==5)
                A = imread('re_cylinder_alt.jpg');
            end
            if(n==6)
                A = imread('Cylinder_md_alt.jpg');  
            end
            
            figure(999)
            imshow(A,'border','tight','InitialMagnification',100) 
        end

        % Value changed function: InputFormatListBox
        function InputFormatListBoxValueChanged(app, event)
            
            SPLFormat(app);

        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
           
            try
                CylindricalShell.SPLname=app.SPLNameEditField.Value;
            catch
            end

            try
                CylindricalShell.iformat=app.InputFormatListBox.Value;
            catch
            end

            try
                FS=app.SPLNameEditField.Value;
                CylindricalShell.FS=FS;
            catch
            end

            try                 
                app.THM=evalin('base',FS);
                CylindricalShell.THM=app.THM;
            catch
            end            


            Items = strtrim(app.BoundaryConditionsListBox.Items);
            im = strtrim(app.BoundaryConditionsListBox.Value);
            bc = find(strcmp(Items, im));

            try
                CylindricalShell.BC=bc;
            catch
            end 

            try
                CylindricalShell.UITable=app.UITable.Data;
            catch
            end 

            try
                CylindricalShell.UITable_material=app.UITable_material.Data;
            catch
            end 

            try
                CylindricalShell.UITable_geometry=app.UITable_geometry.Data;
            catch
            end 

            try
                CylindricalShell.UITable_plots=app.UITable_plots.Data;
            catch
            end             

            try
                CylindricalShell.LF=app.UniformLossFactorEditField.Value;
            catch
            end            
  
            try
                CylindricalShell.name=app.CylinderNameEditField.Value;
            catch
            end   

            try
                CylindricalShell.fmin=app.MinEditField.Value;
            catch
            end 

            try
                CylindricalShell.fmax=app.MaxEditField.Value;
            catch
            end 

            try
                CylindricalShell.ifield=app.ifield;
            catch
            end             

            try
                CylindricalShell.igas=app.igas;
            catch
            end            

            try
                CylindricalShell.imat=app.imat;
            catch
            end             

            try
                CylindricalShell.units=app.UnitsListBox.Value;             
            catch
            end

            try
                CylindricalShell.gas=app.SurroundingGasListBox.Value;
            catch
            end

            try
                CylindricalShell.material=app.MaterialListBox.Value;
            catch
            end

            try
                CylindricalShell.field=app.AcousticFieldListBox.Value;
            catch
            end

            % % %
 
            structnames = fieldnames(CylindricalShell, '-full'); % fields in the struct
             
            % % %
             
            [writefname, writepname] = uiputfile('*.mat','Save data as');
           
            elk=sprintf('%s%s',writepname,writefname);
         
            try
         
                save(elk, 'CylindricalShell'); 
         
            catch
                warndlg('Save error');
                return;
            end
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat');
 
            NAME = [pathname,filename];
             
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
             
            disp(' ref 2');
             
            k=length(structnames);
             
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
             
            disp(' ref 3');
             
            structnames
             
             
            % struct
             
            try
                CylindricalShell=evalin('base','CylindricalShell');
            catch
               warndlg(' evalin failed ');
               return;
            end
             
            CylindricalShell
           

            try
                app.UnitsListBox.Value=CylindricalShell.units;
                change(app);
            catch
            end

            try
                app.InputFormatListBox.Value=CylindricalShell.iformat;
            catch
            end

            update(app);

            try
                app.SPLNameEditField.Value=CylindricalShell.SPLname;
            catch
            end

            try
                app.SelectInputArrayListBox.Value=CylindricalShell.SPLname;
            catch
            end

            try
                FS1=CylindricalShell.FS;
               
                iflag=0;
                
                try
                    temp=evalin('base',FS1);
                    ss=sprintf('Replace %s with Previously Saved Array',FS1);
                    answer = questdlg(ss,'Array Overwrite','Yes','No','No');
            
                    switch answer
                        case 'Yes'
                            iflag=0;
                        case 'No'
                            iflag=1;
                    end    
                catch 
                end
                
                if(iflag==0)
                    try
                        THM1=CylindricalShell.THM;
                        assignin('base',FS1,THM1); 
                    catch
                    end
                end
                
            catch    
            end

            try
                bc=CylindricalShell.BC;
                app.BoundaryConditionsListBox.Value=app.BoundaryConditionsListBox.Items{bc};
            catch
            end 


            try
                app.ifield=CylindricalShell.ifield;
            catch
            end          

            try
                app.AcousticFieldListBox.Value=CylindricalShell.field;
            catch
            end

            try
                app.igas=CylindricalShell.igas;
            catch
            end  

            try
                app.SurroundingGasListBox.Value=CylindricalShell.gas;
            catch
            end          

            try
                app.imat=CylindricalShell.imat;
            catch
            end             

            try
                app.MaterialListBox.Value=CylindricalShell.material;
            catch
            end

            change(app);


            try
                app.UITable.Data=CylindricalShell.UITable;
            catch
            end 

            try
                app.UITable_material.Data=CylindricalShell.UITable_material;
            catch
            end 
            try
                app.UITable_geometry.Data=CylindricalShell.UITable_geometry;
            catch
            end

            try
                app.UITable_plots.Data=CylindricalShell.UITable_plots;
            catch
            end      

            try
                app.UniformLossFactorEditField.Value=CylindricalShell.LF;
            catch
            end            

            try
                app.CylinderNameEditField.Value=CylindricalShell.name;
            catch
            end   

            try
                app.MinEditField.Value=CylindricalShell.fmin;
            catch
            end 

            try
                app.MaxEditField.Value=CylindricalShell.fmax;
            catch
            end 

            msgbox('Load Complete');
 
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.imethod]=find_index(Items,im);

            if(app.imethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   app.THM = importdata(filename);
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               app.THM

               try
                    update(app);             
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    


        end

        % Value changed function: BoundaryConditionsListBox
        function BoundaryConditionsListBoxValueChanged(app, event)
            
            
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            
            try
                ff = app.SelectInputArrayListBox.Value;
                app.SPLNameEditField.Value=ff;
            catch
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SEAOneCylindricalShellUIFigure = uifigure('Visible', 'off');
            app.SEAOneCylindricalShellUIFigure.Name = 'SEA One Cylindrical Shell';
            app.ReturnButton = uibutton(app.SEAOneCylindricalShellUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SEA_one_cylindrical_shell_Appver26byTomIrvineLabel = uilabel(app.SEAOneCylindricalShellUIFigure);
            app.SEA_one_cylindrical_shell_Appver26byTomIrvineLabel.Text = 'SEA_one_cylindrical_shell_App  ver 2.6  by Tom Irvine';
            app.TabGroup = uitabgroup(app.SEAOneCylindricalShellUIFigure);
            app.AnalysisTypeGasTab = uitab(app.TabGroup);
            app.AnalysisTypeGasTab.Title = 'Analysis Type & Gas';
            app.Image = uiimage(app.AnalysisTypeGasTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'one_system_sea_alt_small.jpg');
            app.UnitsListBoxLabel = uilabel(app.AnalysisTypeGasTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.AnalysisTypeGasTab);
            app.UnitsListBox.Items = {'English', 'Metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.SurroundingGasListBoxLabel = uilabel(app.AnalysisTypeGasTab);
            app.SurroundingGasListBoxLabel.Text = 'Surrounding Gas';
            app.SurroundingGasListBox = uilistbox(app.AnalysisTypeGasTab);
            app.SurroundingGasListBox.Items = {'Air', 'Other'};
            app.SurroundingGasListBox.ValueChangedFcn = createCallbackFcn(app, @SurroundingGasListBoxValueChanged, true);
            app.SurroundingGasListBox.Value = 'Air';
            app.AcousticFieldListBoxLabel = uilabel(app.AnalysisTypeGasTab);
            app.AcousticFieldListBoxLabel.Text = 'Acoustic Field';
            app.AcousticFieldListBox = uilistbox(app.AnalysisTypeGasTab);
            app.AcousticFieldListBox.Items = {'Diffuse', 'Turbulent Boundary Layer'};
            app.AcousticFieldListBox.ValueChangedFcn = createCallbackFcn(app, @AcousticFieldListBoxValueChanged, true);
            app.AcousticFieldListBox.Value = 'Diffuse';
            app.UITable = uitable(app.AnalysisTypeGasTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.MaterialGeometryTab = uitab(app.TabGroup);
            app.MaterialGeometryTab.Title = 'Material & Geometry';
            app.UITable_material = uitable(app.MaterialGeometryTab);
            app.UITable_material.ColumnName = {'Parameter'; 'Value'};
            app.UITable_material.RowName = {};
            app.UITable_material.ColumnEditable = [false true];
            app.UITable_geometry = uitable(app.MaterialGeometryTab);
            app.UITable_geometry.ColumnName = {'Parameter'; 'Value'};
            app.UITable_geometry.RowName = {};
            app.UITable_geometry.ColumnEditable = [false true];
            app.MaterialListBoxLabel = uilabel(app.MaterialGeometryTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialGeometryTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.BoundaryConditionsListBoxLabel = uilabel(app.MaterialGeometryTab);
            app.BoundaryConditionsListBoxLabel.Text = 'Boundary Conditions';
            app.BoundaryConditionsListBox = uilistbox(app.MaterialGeometryTab);
            app.BoundaryConditionsListBox.Items = {'Free-Free                 ', 'Fixed-Fixed               ', 'Fixed-Free                ', 'Simply Supported both Ends', ''};
            app.BoundaryConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsListBoxValueChanged, true);
            app.BoundaryConditionsListBox.Value = 'Free-Free                 ';
            app.InputOneThirdOctaveSPLTab = uitab(app.TabGroup);
            app.InputOneThirdOctaveSPLTab.Title = 'Input One-Third Octave SPL';
            app.ZerodBref20microPaLabel = uilabel(app.InputOneThirdOctaveSPLTab);
            app.ZerodBref20microPaLabel.Text = 'Zero dB ref:  20 micro Pa';
            app.ThelossfactorsareinternaldissipationLabel = uilabel(app.InputOneThirdOctaveSPLTab);
            app.ThelossfactorsareinternaldissipationLabel.Text = 'The loss factors are internal dissipation.';
            app.InputFormatListBoxLabel = uilabel(app.InputOneThirdOctaveSPLTab);
            app.InputFormatListBoxLabel.Text = 'Input Format';
            app.InputFormatListBox = uilistbox(app.InputOneThirdOctaveSPLTab);
            app.InputFormatListBox.Items = {'Three columns:  freq (Hz), SPL(dB), loss factor', 'Two columns:  freq (Hz), SPL(dB)'};
            app.InputFormatListBox.ValueChangedFcn = createCallbackFcn(app, @InputFormatListBoxValueChanged, true);
            app.InputFormatListBox.Value = 'Two columns:  freq (Hz), SPL(dB)';
            app.UniformLossFactorEditFieldLabel = uilabel(app.InputOneThirdOctaveSPLTab);
            app.UniformLossFactorEditFieldLabel.Text = 'Uniform Loss Factor';
            app.UniformLossFactorEditField = uieditfield(app.InputOneThirdOctaveSPLTab, 'text');
            app.listbox_method = uilistbox(app.InputOneThirdOctaveSPLTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputOneThirdOctaveSPLTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputOneThirdOctaveSPLTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputOneThirdOctaveSPLTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputOneThirdOctaveSPLTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.SelectInputArrayListBox.Value = {};
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CylinderNameLabel = uilabel(app.CalculateTab);
            app.CylinderNameLabel.Text = 'Cylinder Name';
            app.CylinderNameEditField = uieditfield(app.CalculateTab, 'text');
            app.UITable_plots = uitable(app.CalculateTab);
            app.UITable_plots.ColumnName = {'Function'; 'Plot'; 'Save'};
            app.UITable_plots.RowName = {};
            app.UITable_plots.ColumnEditable = [false true true];
            app.MinEditFieldLabel = uilabel(app.CalculateTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculateTab, 'text');
            app.MaxEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculateTab, 'text');
            app.PlotFrequenciesLabel = uilabel(app.CalculateTab);
            app.PlotFrequenciesLabel.Text = 'Plot Frequencies';
            app.SPLNameEditFieldLabel = uilabel(app.CalculateTab);
            app.SPLNameEditFieldLabel.Text = 'SPL Name';
            app.SPLNameEditField = uieditfield(app.CalculateTab, 'text');
            app.SPLNameEditField.Editable = 'off';
            app.ModalDensityMethodListBoxLabel = uilabel(app.CalculateTab);
            app.ModalDensityMethodListBoxLabel.Text = 'Modal Density Method';
            app.ModalDensityMethodListBox = uilistbox(app.CalculateTab);
            app.ModalDensityMethodListBox.Items = {'Heckl', 'Szechenyi', 'Bing-ru et al'};
            app.ModalDensityMethodListBox.Value = 'Szechenyi';
            app.ViewEquationsTab = uitab(app.TabGroup);
            app.ViewEquationsTab.Title = 'View Equations';
            app.EquationListBoxLabel = uilabel(app.ViewEquationsTab);
            app.EquationListBoxLabel.Text = 'Equation';
            app.EquationListBox = uilistbox(app.ViewEquationsTab);
            app.EquationListBox.Items = {'System                            ', 'Coupling Loss Factor', 'Sound Power, Diffuse', 'Sound Power, TBL', 'Radiation Efficiency', 'Modal Density'};
            app.EquationListBox.Value = 'System                            ';
            app.ViewButton = uibutton(app.ViewEquationsTab, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.SaveModelButton = uibutton(app.SEAOneCylindricalShellUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.SEAOneCylindricalShellUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            % Show the figure after all components are created
            app.SEAOneCylindricalShellUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEA_one_cylindrical_shell_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SEAOneCylindricalShellUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SEAOneCylindricalShellUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEAOneCylindricalShellUIFigure)
        end
    end
end