classdef SVD_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISVDfilterUIFigure             matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        SVD_filterver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SVDTab                          matlab.ui.container.Tab
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        MaximumNumberofPointstoIncludeEditFieldLabel  matlab.ui.control.Label
        MaximumNumberofPointstoIncludeEditField  matlab.ui.control.NumericEditField
        SignalProcessingListBoxLabel    matlab.ui.control.Label
        SignalProcessingListBox         matlab.ui.control.ListBox
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        CalculateSVDButton              matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        YaxisLabelEditFieldLabel        matlab.ui.control.Label
        SingularValueThresholdRatioEditFieldLabel  matlab.ui.control.Label
        SingularValueThresholdRatioEditField  matlab.ui.control.NumericEditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        CalculateFilterButton           matlab.ui.control.Button
        YaxisLabelEditField             matlab.ui.control.EditField
        FilterTab                       matlab.ui.container.Tab
        FFTTab                          matlab.ui.container.Tab
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel_2  matlab.ui.control.Label
        CalculateFFTButton              matlab.ui.control.Button
        InputArrayNameEditField_2Label  matlab.ui.control.Label
        InputArrayNameEditField_FFT     matlab.ui.control.EditField
        MeanRemovalListBoxLabel         matlab.ui.control.Label
        MeanRemovalListBox              matlab.ui.control.ListBox
        WindowListBoxLabel              matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        PlotMaxFreqHzEditFieldLabel     matlab.ui.control.Label
        PlotMaxFreqHzEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fig_num   % 
        filtered  %
        THM       %
        hw_choice %
        amp       %
        ttt       %
        tend      %
        fmax      % 
        freq      % 
        full      % 
        phase     % 
        num       % 
        Gxx                 % 
        complex_FT          % 
        magnitude_FT        % 
        magnitude_phase_FT  % 
        yname               % 
        nhalf     %
        dt        %
        df        %
        s         %
        u         %
        v         %
        nnum      %
        q         %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                     
        end
        
        function Signal(app)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';            
            end            
        end
        
        function PreProcess(app)

            ClosePlots(app);
        
            FS = app.InputArrayNameEditField_FFT.Value;
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end    
            
            app.fmax=app.PlotMaxFreqHzEditField.Value;
            
            nmr=app.MeanRemovalListBox.Value;
            
            if(contains(nmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            nhw=app.WindowListBox.Value;
            
            if(contains(nhw,'Rectangular'))
                app.hw_choice=1;
            else
                app.hw_choice=2;
            end
            
            app.tend=app.THM(end,1);
            
            tdif=diff(app.THM(:,1));
            min_dt=min(tdif);
            max_dt=max(tdif);
            
            ratio=abs((max_dt-min_dt)/max_dt);
            
            if(ratio>0.05)
                fprintf('\n\n min_dt=%8.4g  max_dt=%8.4g \n\n',min_dt,max_dt);
                warndlg('Time step must be constant');
                return;
            end
            
            app.amp=app.THM(:,2);
            
            app.num=length(app.amp);
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.amp(1:app.num);
            app.ttt=app.THM(1:app.num,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            fprintf('\n num=%d  dt=%8.4g  \n\n',app.num,app.dt);
            
            [app.amp]=mean_removal_Hanning(app.amp,mr_choice,app.hw_choice);
            
        end
        
        function PostProcess(app)
            if(app.hw_choice==1)
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.ttt,app.amp);
                title('Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
            else
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                
                subplot(2,1,1);   
                plot(app.THM(:,1),app.THM(:,2));
                title('Filtered Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
                
                subplot(2,1,2); 
                plot(app.ttt,app.amp);
                title('Filtered Time History with Hanning Window');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]); 
                grid on;    
            end
            
            if(app.fmax==0)
                sr=1/app.dt;
                app.fmax=sr/2;
            end
            
            [~,I]=max(app.magnitude_FT(:,2));
            fmaxp=app.magnitude_FT(I,1);
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.magnitude_FT(:,1),app.magnitude_FT(:,2));
            out1=sprintf('FFT Magnitude Filtered  Max Peak at %8.4g Hz',fmaxp);
            title(out1);
            ylabel(app.yname);
            xlabel('Frequency (Hz)');
            xlim([0 app.fmax]);
            grid on;     
            
            md=4;
            ylab=app.yname;
            fmin=0;
            
            fff=app.magnitude_phase_FT(:,1);
            FRF_m=app.magnitude_phase_FT(:,2);            
            FRF_p=app.magnitude_phase_FT(:,3);
            t_string=sprintf('FFT Magnitude & Phase Filtered Max Peak at %8.4g Hz',fmaxp);

%           [app.fig_num]=...
%                plot_magnitude_phase_function_linear(app.fig_num,t_string,fmin,app.fmax,fff,FRF_p,FRF_m,ylab,md);            
            
            nf=length(fff);
            
            app.complex_FT(:,2)=app.complex_FT(:,2)+(1i)*app.complex_FT(:,3);
            app.complex_FT(:,3)=[];
            
            app.Gxx=zeros(nf,1);
            app.Gxx(1)=abs(app.complex_FT(1,2));     
            
            for i=2:nf
                 X=app.complex_FT(i,2);     
            	app.Gxx(i)=transpose(X)*X;
            end
            
            app.Gxx=[fff abs(app.Gxx)];                
                
%            app.SaveButton.Enable='on'; 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            Signal(app); 
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateSVDButton
        function CalculateSVDButtonPushed(app, event)
            
            tic;
            
            disp('  ');
            disp(' * * * * * * * ');
            disp('  ');
            
            FS = app.InputArrayNameEditField.Value;
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end 
            
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Segment'))
                tstart=app.StartTimesecEditField.Value;
                ttend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(ttend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);     
            end
            
            
            app.q=app.THM(:,2);
            app.nnum=length(app.q);
            
            N=app.MaximumNumberofPointstoIncludeEditField.Value;
            
            if(N>6000)
                warndlg('Maximum Number of Input Points reset to 6000');
                app.MaximumNumberofPointstoIncludeEditField.Value=6000;
                N=6000;
            end
            
            if(app.nnum>N)
                app.THM=app.THM(1:N,:);
                app.q=app.THM(:,2);   
            end
            
            H = hankel(app.q);
            disp('Hankel array formed');
            
            [app.u,app.s,app.v] = svd(H);
            disp('SVD completed');
            
            app.nnum=length(app.q);
            
            fprintf('Total points = %d \n',app.nnum);
            
            sz=size(app.s);
            app.nnum=sz(1);
            
            fprintf('\n Singular Values, 1 to 12 \n');
            fprintf(' i       S      Ratio \n');
            for i=1:min([12 app.nnum])
                fprintf(' %d  %8.4g  %8.4g \n',i,app.s(i,i),app.s(i,i)/app.s(1,1))
            end
           
            
            toc            
            
            msgbox('SVD Calculation Complete');
            
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);     
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            PreProcess(app);
            [app.freq,app.full,app.phase,app.complex_FT]=full_FFT_core_alt(app.amp,app.num,app.df);
            app.magnitude_FT=[app.freq app.full];
            app.magnitude_phase_FT=[app.freq app.full app.phase];
            PostProcess(app);
        end

        % Button pushed function: CalculateFilterButton
        function CalculateFilterButtonPushed(app, event)
            app.fig_num=1;

            ratio=app.SingularValueThresholdRatioEditField.Value;
            
            ss=app.s;
            qx=app.q;
            
            sz=size(ss);
            app.nnum=sz(1);
            
            th=ratio*ss(1,1);
            
            ijk=0;
            
            ds=zeros(app.nnum,1);
            
            ds(1)=ss(1,1);
            
            for i=2:app.nnum
                
                ds(i)=ss(i,i);
                
                if(ss(i,i)<th)
                    ss(i,i)=0;
                    ijk=ijk+1;
                end
            end
            
            nz=app.nnum-ijk;
            
            fprintf('SVD nonzero values number %d \n',nz);
            
            disp('SVD truncation completed');
            
            B=app.u*ss*app.v';
            
            qq=zeros(app.nnum,1);
            
            qq(1)=B(1,1);
            
            for i=2:app.nnum
                k=0;
                for j=1:i-1
                    qq(i)=qq(i)+B(j,i-j);
                    k=k+1;
                end
                qq(i)=qq(i)/k;
            end

            disp('Filtering completed');
            
            app.yname=app.YaxisLabelEditField.Value;
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            plot(app.THM(:,1),qx);
            xlabel('Time (sec)');
            ylabel(app.yname);
            title('Input Time History');
            grid on;
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            L=length(qq);            
            
            plot(app.THM(1:L,1),qq);
            xlabel('Time (sec)');  
            ylabel(app.yname);  
            title('Filtered Time History');
            grid on;
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            
            
            plot(app.THM(1:L,1),qx-qq);
            xlabel('Time (sec)');  
            ylabel(app.yname);  
            title('Residual Time History');
            grid on;            
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            plot(app.THM(1:L,1),qx,app.THM(1:L,1),qq);
            xlabel('Time (sec)');  
            ylabel(app.yname);  
            title('Time History');
            legend('Input','Filtered');
            grid on;
            
            app.filtered=[app.THM(:,1),qq];
            
            try
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name, app.filtered); 
            catch
            end
            
            try
                app.InputArrayNameEditField_FFT.Value=app.OutputArrayNameEditField.Value;
            catch  
            end
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            plot(ds);
            title('Singular Values prior to Truncation');
            grid on;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISVDfilterUIFigure = uifigure('Visible', 'off');
            app.UISVDfilterUIFigure.Name = 'UI SVD filter';
            app.ReturnButton = uibutton(app.UISVDfilterUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SVD_filterver10byTomIrvineLabel = uilabel(app.UISVDfilterUIFigure);
            app.SVD_filterver10byTomIrvineLabel.Text = 'SVD_filter    ver 1.0   by Tom Irvine      Singular Value Decomposition filter for short time histories of less than 6000 points';
            app.TabGroup = uitabgroup(app.UISVDfilterUIFigure);
            app.SVDTab = uitab(app.TabGroup);
            app.SVDTab.Title = 'SVD';
            app.InputArrayNameEditFieldLabel = uilabel(app.SVDTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.SVDTab, 'text');
            app.MaximumNumberofPointstoIncludeEditFieldLabel = uilabel(app.SVDTab);
            app.MaximumNumberofPointstoIncludeEditFieldLabel.Text = 'Maximum Number of Points to Include';
            app.MaximumNumberofPointstoIncludeEditField = uieditfield(app.SVDTab, 'numeric');
            app.MaximumNumberofPointstoIncludeEditField.Value = 6000;
            app.SignalProcessingListBoxLabel = uilabel(app.SVDTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.SVDTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.SVDTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.SVDTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.SVDTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.SVDTab, 'numeric');
            app.CalculateSVDButton = uibutton(app.SVDTab, 'push');
            app.CalculateSVDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSVDButtonPushed, true);
            app.CalculateSVDButton.Text = 'Calculate SVD';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.SVDTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time(sec) & amplitude';
            app.YaxisLabelEditFieldLabel = uilabel(app.SVDTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.SVDTab, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.SingularValueThresholdRatioEditFieldLabel = uilabel(app.FilterTab);
            app.SingularValueThresholdRatioEditFieldLabel.Text = 'Singular Value Threshold Ratio';
            app.SingularValueThresholdRatioEditField = uieditfield(app.FilterTab, 'numeric');
            app.SingularValueThresholdRatioEditField.Value = 0.044;
            app.OutputArrayNameEditFieldLabel = uilabel(app.FilterTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.FilterTab, 'text');
            app.OutputArrayNameEditField.Value = 'filtered_data';
            app.CalculateFilterButton = uibutton(app.FilterTab, 'push');
            app.CalculateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFilterButtonPushed, true);
            app.CalculateFilterButton.Text = 'Calculate Filter';
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel_2 = uilabel(app.FFTTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel_2.Text = 'The input array must have two columns:  time(sec) & amplitude';
            app.CalculateFFTButton = uibutton(app.FFTTab, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.InputArrayNameEditField_2Label = uilabel(app.FFTTab);
            app.InputArrayNameEditField_2Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_FFT = uieditfield(app.FFTTab, 'text');
            app.MeanRemovalListBoxLabel = uilabel(app.FFTTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.FFTTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.FFTTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.FFTTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning'};
            app.WindowListBox.Value = 'Rectangular';
            app.PlotMaxFreqHzEditFieldLabel = uilabel(app.FFTTab);
            app.PlotMaxFreqHzEditFieldLabel.Text = 'Plot Max Freq (Hz)';
            app.PlotMaxFreqHzEditField = uieditfield(app.FFTTab, 'numeric');
            % Show the figure after all components are created
            app.UISVDfilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SVD_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISVDfilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISVDfilterUIFigure)
        end
    end
end