classdef Spann_method_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpannUIFigure                   matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputSPLTab                     matlab.ui.container.Tab
        InputArrayFormat                matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        OneThirdOctaveInputTypeListBox  matlab.ui.control.ListBox
        OneThirdOctaveInputTypeListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculatePSDTab_2               matlab.ui.container.Tab
        lbmin2EditField                 matlab.ui.control.EditField
        lbmin2Label                     matlab.ui.control.Label
        SurfaceAreain2EditField         matlab.ui.control.EditField
        SurfaceAreain2EditFieldLabel    matlab.ui.control.Label
        MasslbmEditField                matlab.ui.control.EditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        TotalSurfaceMassDensityincludingSupportStructureComponentLabel  matlab.ui.control.Label
        SupportStructureComponentLabel  matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.EditField
        BetaEditFieldLabel              matlab.ui.control.Label
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        MassMethodListBox               matlab.ui.control.ListBox
        MassMethodListBoxLabel          matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SaveAccelPSDTab                 matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        WorkspaceArrayNameEditField     matlab.ui.control.EditField
        WorkspaceArrayNameEditFieldLabel  matlab.ui.control.Label
        ConverttoStressPSDButton        matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        Spann_method_aver14byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        method
        mass_method
        input_method
        iu
        Wa
    end
    
    methods (Access = private)

        function Change(app)

            ClosePlots(app);
            app.SaveButton.Enable='off';
            
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            Items = strtrim(app.MassMethodListBox.Items);
            im = strtrim(app.MassMethodListBox.Value);
            app.mass_method = find(strcmp(Items, im));           
            
            Items = strtrim(app.OneThirdOctaveInputTypeListBox.Items);
            im = strtrim(app.OneThirdOctaveInputTypeListBox.Value);
            app.input_method = find(strcmp(Items, im));             

            %
            
            if(app.iu==1)
                app.MasslbmEditFieldLabel.Text='Mass (lbm)';
                app.SurfaceAreain2EditFieldLabel.Text='Surface Area (in^2)';
                app.lbmin2Label.Text='(lbm/in^2)';
            else
                app.MasslbmEditFieldLabel.Text='Mass (kg)';   
                app.SurfaceAreain2EditFieldLabel.Text='Surface Area (m^2)'; 
                app.lbmin2Label.Text='(kg/m^2)';    
            end
            
            
            if(app.input_method==1) % SPL
                
                ss='Input Array format:  center freq(Hz) & SPL(dB)';
            
            else     % PSD
                
                if(app.iu==1) % English
                    ss='Input Array format:  center freq(Hz) & PSD (psi^2/Hz)';
                else     % metric
                    ss='Input Array format:  center freq(Hz) & PSD (Pa^2/Hz)';
                end    
            end

            app.InputArrayFormat.Text=ss;

            app.SupportStructureComponentLabel.Visible='on';
            app.MasslbmEditField.Visible='on';
            app.MasslbmEditFieldLabel.Visible='on';
            app.TotalSurfaceMassDensityincludingSupportStructureComponentLabel.Visible='on';
            app.lbmin2EditField.Visible='on';
            app.lbmin2Label.Visible='on';
            app.SurfaceAreain2EditField.Visible='on';
            app.SurfaceAreain2EditFieldLabel.Visible='on';      
            
            if(app.mass_method==1)
                app.TotalSurfaceMassDensityincludingSupportStructureComponentLabel.Visible='off';
                app.lbmin2EditField.Visible='off';
                app.lbmin2Label.Visible='off';
            else
                app.SupportStructureComponentLabel.Visible='off';
                app.MasslbmEditField.Visible='off';
                app.MasslbmEditFieldLabel.Visible='off';
                app.SurfaceAreain2EditField.Visible='off';
                app.SurfaceAreain2EditFieldLabel.Visible='off';

            end
            
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function update(app)
            ClosePlots(app);
            try
                cell_array=update_spl();
            catch
                msgbox('updata_spl found no files');
            end
            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
                app.listbox_method.Value=app.listbox_method.Items{1}; 
            catch
            end
        end
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);  
            update(app);
            Change(app);
            app.SaveButton.Enable='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               app.THM

               try
                    update(app);             
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end   
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: OneThirdOctaveInputTypeListBox
        function OneThirdOctaveInputTypeListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: MassMethodListBox
        function MassMethodListBoxValueChanged(app, event)
            Change(app);
        end

        % Button pushed function: ConverttoStressPSDButton
        function ConverttoStressPSDButtonPushed(app, event)
            vibrationdata_stress_velocity_a;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            if(isempty(app.Wa))
                warndlg('Calculate Acceleration PSD first');
                return;
            end

            try
                output_name=app.WorkspaceArrayNameEditField.Value;
                assignin('base', output_name, app.Wa);
                msgbox('Save Complete'); 
            catch
                warndlg('Save Failed');
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);
            
            % fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end

            Q=str2num(app.AmplificationFactorQEditField.Value);
            beta=str2num(app.BetaEditField.Value);
            
            if(app.mass_method==1)
                mass=str2num(app.MasslbmEditField.Value);
                area=str2num(app.SurfaceAreain2EditField.Value);
                if(app.iu==1)
                    mass=mass/386;
                else
                    mass=mass*9.81;
                end
                scale=(Q*beta*area/mass)^2;
            else
                smd=str2num(app.lbmin2EditField.Value);
                if(app.iu==1)
                    smd=smd/386;
                else
                    smd=smd*9.81;
                end    
                scale=(Q*beta/smd)^2;
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            app.THM=fix_size(app.THM);
            sz=size(app.THM);   
            
            num=sz(1);
            
            app.Wa=zeros(num,2);
            
            if(app.input_method==1) % spl
                
               if(app.iu==1) 
                   Pref=2.9e-09;  % psi
               else
                   Pref=20e-06;   % Pa
               end
            
               Wp=zeros(num,2);
               Wp(:,1)=app.THM(:,1);
               
               SPL=app.THM(:,2);
               
               x=2^(1/6)-1/(2^(1/6));
               
               for i=1:num
                   dfc=Wp(i,1)*x;
                   Wp(i,2)=(Pref^2/dfc)*10^(SPL(i)/10 );
               end
               
                suma=0.;
            %
                for i=1:num
            %
                    rms = (10.^(SPL(i)/10));
                    suma=suma+rms;
                end
            %
                OASPL=10.*log10(suma);   
               
                figure(fig_num)
                fig_num=fig_num+1;
                plot(app.THM(:,1),app.THM(:,2));
                set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','lin')
                grid on;
                out1=sprintf(' One-Third Octave Sound Pressure Level  OASPL = %8.4g dB ',OASPL);
                title(out1)
                xlabel(' Center Frequency (Hz) ');
                ylabel(' SPL (dB) ');   
               

            else     % pressure psd
               Wp=app.THM;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            

            app.Wa(:,1)=Wp(:,1);
            
            num=sz(1);
            
            for i=1:num
               app.Wa(i,2)=scale*Wp(i,2); 
            end
            
            if(app.iu==1)
                app.Wa(:,2)=app.Wa(:,2)/386^2;
            else
                app.Wa(:,2)=app.Wa(:,2)/9.81^2;
            end
            
            [~,prms] = calculate_PSD_slopes_no(Wp(:,1),Wp(:,2));
            [~,grms] = calculate_PSD_slopes_no(app.Wa(:,1),app.Wa(:,2));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=app.Wa(1,1);
            fmax=app.Wa(num,1);
            
            if(app.iu==1)
                t_string = sprintf('Pressure Power Spectral Density  %7.3g psi RMS Overall',prms);
                y_label='Pressure (psi^2/Hz)';
            else
                t_string = sprintf('Pressure Power Spectral Density  %7.3g Pa RMS Overall',prms);
                y_label='Pressure (Pa^2/Hz)';    
            end    
                
            x_label='Frequency (Hz)';
            
            [fig_num,~]=plot_PSD_function(fig_num,x_label,y_label,t_string,Wp,fmin,fmax);


            t_string = sprintf(' Acceleration Power Spectral Density  %7.3g GRMS Overall',grms);
            x_label='Frequency (Hz)';
            y_label='Accel (G^2/Hz)';
            
            [fig_num,~]=plot_PSD_function(fig_num,x_label,y_label,t_string,app.Wa,fmin,fmax);

            app.SaveButton.Enable='on';


        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            ClosePlots(app);
            app.SaveButton.Enable='off';
        end

        % Value changing function: BetaEditField
        function BetaEditFieldValueChanging(app, event)
            ClosePlots(app);
            app.SaveButton.Enable='off';
        end

        % Value changing function: MasslbmEditField
        function MasslbmEditFieldValueChanging(app, event)
            ClosePlots(app);
            app.SaveButton.Enable='off';
        end

        % Value changing function: SurfaceAreain2EditField
        function SurfaceAreain2EditFieldValueChanging(app, event)
            ClosePlots(app);
            app.SaveButton.Enable='off';
        end

        % Value changing function: lbmin2EditField
        function lbmin2EditFieldValueChanging(app, event)
            ClosePlots(app);
            app.SaveButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpannUIFigure = uifigure('Visible', 'off');
            app.SpannUIFigure.Name = 'Spann';
            app.Spann_method_aver14byTomIrvineLabel = uilabel(app.SpannUIFigure);
            app.Spann_method_aver14byTomIrvineLabel.Text = 'Spann_method_a   ver 1.4  by Tom Irvine';
            app.ReturnButton = uibutton(app.SpannUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.SpannUIFigure);
            app.InputSPLTab = uitab(app.TabGroup);
            app.InputSPLTab.Title = 'Input SPL';
            app.listbox_method = uilistbox(app.InputSPLTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputSPLTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputSPLTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputSPLTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputSPLTab);
            app.SelectInputArrayListBox.Items = {''};
            app.SelectInputArrayListBox.Value = '';
            app.OneThirdOctaveInputTypeListBoxLabel = uilabel(app.InputSPLTab);
            app.OneThirdOctaveInputTypeListBoxLabel.Text = 'One-Third Octave Input Type';
            app.OneThirdOctaveInputTypeListBox = uilistbox(app.InputSPLTab);
            app.OneThirdOctaveInputTypeListBox.Items = {'SPL ref 20 micro Pa', 'Pressure PSD'};
            app.OneThirdOctaveInputTypeListBox.ValueChangedFcn = createCallbackFcn(app, @OneThirdOctaveInputTypeListBoxValueChanged, true);
            app.OneThirdOctaveInputTypeListBox.Value = 'SPL ref 20 micro Pa';
            app.UnitsListBoxLabel = uilabel(app.InputSPLTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.InputSPLTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.InputArrayFormat = uilabel(app.InputSPLTab);
            app.InputArrayFormat.Text = 'InputArrayFormat';
            app.CalculatePSDTab_2 = uitab(app.TabGroup);
            app.CalculatePSDTab_2.Title = 'Calculate PSD';
            app.CalculateButton = uibutton(app.CalculatePSDTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassMethodListBoxLabel = uilabel(app.CalculatePSDTab_2);
            app.MassMethodListBoxLabel.Text = 'Mass Method';
            app.MassMethodListBox = uilistbox(app.CalculatePSDTab_2);
            app.MassMethodListBox.Items = {'Mass + Area', 'Surface Mass Density'};
            app.MassMethodListBox.ValueChangedFcn = createCallbackFcn(app, @MassMethodListBoxValueChanged, true);
            app.MassMethodListBox.Value = 'Mass + Area';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.CalculatePSDTab_2);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.CalculatePSDTab_2, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '4.5';
            app.BetaEditFieldLabel = uilabel(app.CalculatePSDTab_2);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.CalculatePSDTab_2, 'text');
            app.BetaEditField.ValueChangingFcn = createCallbackFcn(app, @BetaEditFieldValueChanging, true);
            app.BetaEditField.Value = '2.5';
            app.SupportStructureComponentLabel = uilabel(app.CalculatePSDTab_2);
            app.SupportStructureComponentLabel.Text = 'Support Structure + Component';
            app.TotalSurfaceMassDensityincludingSupportStructureComponentLabel = uilabel(app.CalculatePSDTab_2);
            app.TotalSurfaceMassDensityincludingSupportStructureComponentLabel.Text = {'Total Surface Mass Density including '; 'Support Structure & Component'};
            app.MasslbmEditFieldLabel = uilabel(app.CalculatePSDTab_2);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.CalculatePSDTab_2, 'text');
            app.MasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @MasslbmEditFieldValueChanging, true);
            app.SurfaceAreain2EditFieldLabel = uilabel(app.CalculatePSDTab_2);
            app.SurfaceAreain2EditFieldLabel.Text = 'Surface Area (in^2)';
            app.SurfaceAreain2EditField = uieditfield(app.CalculatePSDTab_2, 'text');
            app.SurfaceAreain2EditField.ValueChangingFcn = createCallbackFcn(app, @SurfaceAreain2EditFieldValueChanging, true);
            app.lbmin2Label = uilabel(app.CalculatePSDTab_2);
            app.lbmin2Label.Text = '(lbm/in^2)';
            app.lbmin2EditField = uieditfield(app.CalculatePSDTab_2, 'text');
            app.lbmin2EditField.ValueChangingFcn = createCallbackFcn(app, @lbmin2EditFieldValueChanging, true);
            app.SaveAccelPSDTab = uitab(app.TabGroup);
            app.SaveAccelPSDTab.Title = 'Save Accel PSD';
            app.ConverttoStressPSDButton = uibutton(app.SaveAccelPSDTab, 'push');
            app.ConverttoStressPSDButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoStressPSDButtonPushed, true);
            app.ConverttoStressPSDButton.Text = 'Convert to Stress PSD';
            app.WorkspaceArrayNameEditFieldLabel = uilabel(app.SaveAccelPSDTab);
            app.WorkspaceArrayNameEditFieldLabel.Text = 'Workspace Array Name';
            app.WorkspaceArrayNameEditField = uieditfield(app.SaveAccelPSDTab, 'text');
            app.SaveButton = uibutton(app.SaveAccelPSDTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.SpannUIFigure);
            app.Label.Text = {'Estimate an acceleration PSD via the Spann method for a component base input from the external sound pressure field '; 'exciting the component mounting surface.'};
            % Show the figure after all components are created
            app.SpannUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spann_method_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpannUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SpannUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpannUIFigure)
        end
    end
end