classdef Steinberg_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SteinbergUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CircuitBoardFatigueZ3sigmaLimitTab  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RelativeDisplacementLimitTab    matlab.ui.container.Tab
        CalculateZLimitButton           matlab.ui.control.Button
        ResultsRelativeDisplacementLimitsTextArea  matlab.ui.control.TextArea
        ResultsRelativeDisplacementLimitsTextAreaLabel  matlab.ui.control.Label
        EquivalentPVAccelTab            matlab.ui.container.Tab
        NaturalFrequencyHzEditField_PV  matlab.ui.control.EditField
        NaturalFrequencyHzLabel         matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        CalculatePVButton               matlab.ui.control.Button
        LEditField                      matlab.ui.control.EditField
        LEditFieldLabel                 matlab.ui.control.Label
        hEditField                      matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        inch3sigmafor20millioncyclesLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
        RelativePositionFactorrListBox  matlab.ui.control.ListBox
        RelativePositionFactorrListBoxLabel  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        inLabel_3                       matlab.ui.control.Label
        inLabel_2                       matlab.ui.control.Label
        inLabel                         matlab.ui.control.Label
        LibraryListBox                  matlab.ui.control.ListBox
        LibraryListBoxLabel             matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        ElectronicComponentLengthLabel  matlab.ui.control.Label
        CircuitBoardThicknessLabel      matlab.ui.control.Label
        LengthofCircuitBoardEdgeParalleltoComponentLabel  matlab.ui.control.Label
        CEditFieldLabel                 matlab.ui.control.Label
        ComponentConstantListBox        matlab.ui.control.ListBox
        ComponentConstantListBoxLabel   matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        RelDispPSDTab                   matlab.ui.container.Tab
        ReturnButton_2                  matlab.ui.control.Button
        PSDDamageResultsTextArea        matlab.ui.control.TextArea
        PSDDamageResultsTextAreaLabel   matlab.ui.control.Label
        inch3sigmaLabel                 matlab.ui.control.Label
        Zlimitfor20millioncyclesEditField  matlab.ui.control.EditField
        Zlimitfor20millioncyclesEditFieldLabel  matlab.ui.control.Label
        RelDispUnitListBox              matlab.ui.control.ListBox
        RelDispUnitListBoxLabel         matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculatePSDDamageButton        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RelDispTimeHistoryTab           matlab.ui.container.Tab
        ReturnButton_3                  matlab.ui.control.Button
        SelectInputArrayListBox_TH      matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_TH             matlab.ui.control.Button
        text1_2                         matlab.ui.control.Label
        listbox_method_TH               matlab.ui.control.ListBox
        Zlimitfor20millioncyclesEditField_TH  matlab.ui.control.EditField
        Zlimitfor20millioncyclesEditField_2Label  matlab.ui.control.Label
        RelDispUnitListBox_TH           matlab.ui.control.ListBox
        RelDispUnitListBox_2Label       matlab.ui.control.Label
        inch3sigmaLabel_TH              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TimeHistoryDamageResultsTextArea  matlab.ui.control.TextArea
        TimeHistoryDamageResultsTextAreaLabel  matlab.ui.control.Label
        CalculateTimeHistoryDamageButton  matlab.ui.control.Button
        CircuitBoardDampingTab          matlab.ui.container.Tab
        ReturnButton_4                  matlab.ui.control.Button
        DampingResultsTextArea          matlab.ui.control.TextArea
        DampingResultsTextAreaLabel     matlab.ui.control.Label
        DampingfromSteinbergsempiricalformulaLabel  matlab.ui.control.Label
        ExcitationListBox               matlab.ui.control.ListBox
        ExcitationListBoxLabel          matlab.ui.control.Label
        BaseInputSineGEditField         matlab.ui.control.EditField
        BaseInputSineGEditFieldLabel    matlab.ui.control.Label
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StructureListBox                matlab.ui.control.ListBox
        StructureListBoxLabel           matlab.ui.control.Label
        CalculateDampingButton          matlab.ui.control.Button
        Steinberg_appver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        damping_nex % Description
        method_psd  %
        method_th   %
        RelDisp     %
        THM         %
        iu          %
        Z           %
    end
    
    methods (Access = private)

        function pick(app)
                app.LibraryListBoxLabel.Visible='on';    
                app.LibraryListBox.Visible='on'; 

                Items = strtrim(app.LibraryListBox.Items);
                im = strtrim(app.LibraryListBox.Value);
                n = find(strcmp(Items, im));

                if(n==1)
                    C=0.75;
                end
                if(n==2)
                    C=0.75;
                end
                if(n==3)
                    C=0.75;
                end
                if(n==4)
                    C=1.0;
                end
                if(n==5)
                    C=1.26;
                end
                if(n==6)
                    C=1.0;
                end
                if(n==7)
                    C=2.25;
                end
                if(n==8)
                    C=1.26;
                end
                if(n==9)
                    C=1.75;
                end
                if(n==10)
                    C=0.75;
                end
                if(n==11)
                    C=1.26;
                end
                
                app.CEditField.Value=sprintf('%g',C);
        end    

        function CC(app)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';

            Items = strtrim(app.ComponentConstantListBox.Items);
            im = strtrim(app.ComponentConstantListBox.Value);
            m = find(strcmp(Items, im));

            if(m==1)
                app.LibraryListBoxLabel.Visible='off';    
                app.LibraryListBox.Visible='off'; 
            else
                pick(app);
            end    
            
        end    

        function Sunits(app)

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.inLabel.Text='in';
                app.inLabel_2.Text='in';
                app.inLabel_3.Text='in';
            end    
            if(app.iu==2)
                app.inLabel.Text='mm';
                app.inLabel_2.Text='mm';
                app.inLabel_3.Text='mm';            
            end    
            if(app.iu==3)
                app.inLabel.Text='m';
                app.inLabel_2.Text='m';
                app.inLabel_3.Text='m';            
            end                

        end    

        function update_TH(app)
            disp('ref 1')
            [cell_array]=update_two_columns();

            app.SelectInputArrayListBox_TH.Items=unique(sort(cell_array));  
            app.listbox_method_TH.Value=app.listbox_method_TH.Items{1};
        end    

        function update_psd(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end

        function DampingSine(app)

            Items = strtrim(app.ExcitationListBox.Items);
            im = strtrim(app.ExcitationListBox.Value);
            app.damping_nex = find(strcmp(Items, im));
            
            if(app.damping_nex==1)
                app.BaseInputSineGEditFieldLabel.Text='Base Input Sine (G)';
            else
                ss=sprintf('Base Input PSD (G^2/Hz) \n at Natural Frequency');
                app.BaseInputSineGEditFieldLabel.Text=ss;    
            end

        end    

        function ChangeDispUnit_TH(app)

            Items = strtrim(app.SelectInputArrayListBox_TH.Items);
            im = strtrim(app.SelectInputArrayListBox_TH.Value);
            app.iu = find(strcmp(Items, im));
            
            if(app.iu==1)
               app.inch3sigmaLabel_TH.Text='inch (3-sigma)';
            end
            if(app.iu==2)
               app.inch3sigmaLabel_TH.Text='mm (3-sigma)';    
            end
            if(app.iu==3)
               app.inch3sigmaLabel_TH.Text='m (3-sigma)';    
            end
       
        end    
        function ChangeDispUnit(app)

            Items = strtrim(app.RelDispUnitListBox.Items);
            im = strtrim(app.RelDispUnitListBox.Value);
            app.iu = find(strcmp(Items, im));
            
            if(app.iu==1)
               app.inch3sigmaLabel.Text='inch (3-sigma)';
            end   
            if(app.iu==2)
               app.inch3sigmaLabel.Text='mm (3-sigma)';    
            end
            if(app.iu==3)
               app.inch3sigmaLabel.Text='m (3-sigma)';    
            end

        end    
        
        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CloseFigures(app);
            ChangeDispUnit(app);
            ChangeDispUnit_TH(app);
            CC(app);
            Sunits(app);

            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';

            app.DampingResultsTextAreaLabel.Visible='off';
            app.DampingResultsTextArea.Visible='off';

            app.PSDDamageResultsTextAreaLabel.Visible='off';
            app.PSDDamageResultsTextArea.Visible='off';

            app.TimeHistoryDamageResultsTextAreaLabel.Visible='off';
            app.TimeHistoryDamageResultsTextArea.Visible='off';

            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';

            DampingSine(app);
            update_psd(app);
            update_TH(app);

            app.Z=-999;

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateDampingButton
        function CalculateDampingButtonPushed(app, event)
            
            Items = strtrim(app.StructureListBox.Items);
            im = strtrim(app.StructureListBox.Value);
            n = find(strcmp(Items, im));
            
            fn=str2num(app.NaturalFrequencyHzEditField.Value);

            Gin=str2num(app.BaseInputSineGEditField.Value);

            if(n==1)
                A=1;
                qq='Beam-type Structure';
            end
            if(n==2)
                A=0.5;
                qq='Perimeter-supported PCB';    
            end
            if(n==3)
                A=0.25;
                qq='Small Electronic Chassis';      
            end
            
            if(app.damping_nex==1)
                term=fn/(Gin^0.6);
                Q=A*term^0.76;
                % out1=sprintf('Sine base excitation:  %g G',Gin);
            else
                num=0.49*(A^1.15)*(fn^0.62);
                den=Gin^0.27;
                Q=num/den;   
                % out1=sprintf('Random base excitation:  %g G^2/Hz',Gin); 
            end
            
            damp=1/(2*Q);
            
            sss=sprintf('%s \n\n viscous damping ratio = %6.2g \n\n Q=%7.3g ',qq,damp,Q);
            
            app.DampingResultsTextArea.Value=sss;
            app.DampingResultsTextAreaLabel.Visible='on';
            app.DampingResultsTextArea.Visible='on';
        end

        % Value changed function: StructureListBox
        function StructureListBoxValueChanged(app, event)
      
            app.DampingResultsTextAreaLabel.Visible='off';
            app.DampingResultsTextArea.Visible='off';
        end

        % Value changed function: ExcitationListBox
        function ExcitationListBoxValueChanged(app, event)
            app.DampingResultsTextAreaLabel.Visible='off';
            app.DampingResultsTextArea.Visible='off';

            DampingSine(app);
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            app.DampingResultsTextAreaLabel.Visible='off';
            app.DampingResultsTextArea.Visible='off'; 
        end

        % Value changing function: BaseInputSineGEditField
        function BaseInputSineGEditFieldValueChanging(app, event)
            app.DampingResultsTextAreaLabel.Visible='off';
            app.DampingResultsTextArea.Visible='off';
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
    
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method_psd]=find_index(Items,im);

            if(app.method_psd==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)

            app.PSDDamageResultsTextAreaLabel.Visible='off';
            app.PSDDamageResultsTextArea.Visible='off';
        end

        % Value changed function: RelDispUnitListBox
        function RelDispUnitListBoxValueChanged(app, event)
          
            ChangeDispUnit(app);
            app.PSDDamageResultsTextAreaLabel.Visible='off';
            app.PSDDamageResultsTextArea.Visible='off';
        end

        % Value changing function: Zlimitfor20millioncyclesEditField
        function Zlimitfor20millioncyclesEditFieldValueChanging(app, event)

            app.PSDDamageResultsTextAreaLabel.Visible='off';
            app.PSDDamageResultsTextArea.Visible='off';
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            
            app.PSDDamageResultsTextAreaLabel.Visible='off';
            app.PSDDamageResultsTextArea.Visible='off';
        end

        % Button pushed function: CalculatePSDDamageButton
        function CalculatePSDDamageButtonPushed(app, event)
            
            CloseFigures(app);
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            app.method_psd=find_index(Items,im);

            if(app.method_psd==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
  
            [~,rms]=calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2));
            

            if(app.iu==1)
                out1=sprintf(' Overall level = %7.3g inch RMS ',rms);
                y_label='Rel Disp (in^2/Hz)';
            end
            if(app.iu==2)
                out1=sprintf(' Overall level = %7.3g mm RMS ',rms);    
                y_label='Rel Disp (mm^2/Hz)';
            end
            if(app.iu==3)
                out1=sprintf(' Overall level = %7.3g m RMS ',rms);    
                y_label='Rel Disp (m^2/Hz)';
            end


            disp(' ');
            disp(out1);
            
            fig_num=1;
            ppp=app.THM;
            fmin=ppp(1,1);
            fmax=ppp(end,1);
            md=5;
            t_string=sprintf('Relative Displacement PSD \n %s',out1);
            x_label='Frequency (Hz)';
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);


            app.Z=str2num(app.Zlimitfor20millioncyclesEditField.Value);
            
            
            if(app.iu==2)  % mm to inch conversion
                scale=25.4;
                app.THM(:,2)=app.THM(:,2)/scale^2;
                app.Z=app.Z/scale;
            end
            if(app.iu==3)  % m to inch conversion
                scale=39.37;
                app.THM(:,2)=app.THM(:,2)*scale^2;
                app.Z=app.Z/scale;
            end
            
            T=str2num(app.DurationsecEditField.Value);
            
            [~,amp,EP]=Dirlik_cycles(app.THM,T);
            
            num=length(amp);
            
            rd_Z_ratio = amp/app.Z;
            
            CDI=0;
            
            for i=1:num
                
                cycles=1;
            %
                if(rd_Z_ratio(i)>=6)
                    disp(' Failure:  relative displacement exceeds upper limit. ');
                    warndlg(' Failure:  relative displacement exceeds upper limit. ');
                    return
                end
            %
                N=10^(6.05-6.4*log10(rd_Z_ratio(i)));
            %
                d=(cycles/N);    
                
                CDI=CDI+d;
            end
            
            out44=sprintf(' Max amp = %8.4g \n',max(amp));
            disp(out44);
            
            out00=sprintf(' Max rd_Z_ratio = %8.4g \n',max(rd_Z_ratio));
            disp(out00);
            
            out11=sprintf(' Duration = %g sec   Cycles=%9.5g \n',T,T*EP);
            disp(out11);
            
            out22=sprintf(' CDI = %8.4g \n',CDI);
            disp(out22);
            

            rate=CDI/T;
            
            out33=sprintf(' Damage Rate = %8.4g per sec\n',rate);
            disp(out33);
            
            tfail=(0.7/rate);
            
            out2=sprintf(' Time to failure (R=0.7): \n %8.4g sec \n Cycles=%9.5g \n',tfail,tfail*EP);
            disp(out2);
            
            h=floor(tfail/3600);
            m=floor((tfail-h*3600)/60);
            s=floor( tfail-h*3600-m*60);
            
            
            string1=sprintf(' %g hr %g min %g sec \n',h,m,s);
            disp(string1)
            
            
            dsec=3600*24;
            ysec=dsec*365;
            
            y=floor(tfail/ysec);
            d=floor((tfail-y*ysec)/dsec);
            
            h=floor((tfail-y*ysec-d*dsec)/3600);
            m=floor((tfail-y*ysec-d*dsec-h*3600)/60);
            s=floor( tfail-y*ysec-d*dsec-h*3600-m*60);
               
            
            string=sprintf('                          =  %g years %g days %g hr %g min %g sec',y,d,h,m,s);
            disp(string);
            
            sss=sprintf('CDI = %8.4g \n\n%s \n%s \n%s',CDI,out33,out2,string1);  

            app.PSDDamageResultsTextArea.Value=sss;
            app.PSDDamageResultsTextAreaLabel.Visible='on';
            app.PSDDamageResultsTextArea.Visible='on';
        end

        % Button pushed function: CalculateTimeHistoryDamageButton
        function CalculateTimeHistoryDamageButtonPushed(app, event)
            
            CloseFigures(app);

            Items = char(app.listbox_method_TH.Items);
            im=app.listbox_method_TH.Value;
            app.method_th=find_index(Items,im);

            if(app.method_th==1)

                 try
                     FS=app.SelectInputArrayListBox_TH.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
  
            app.Z=str2num(app.Zlimitfor20millioncyclesEditField_TH.Value);

            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Relative Displacement');
            xlabel('Time(sec)');
            grid on;

            n=app.iu;

            if(n==1)
                ylabel('Rel Disp (in)');
                YS='Rel Disp (in)';
            end    
            if(n==2)
                ylabel('Rel Disp (mm)');
                YS='Rel Disp (mm)';
            end
            if(n==3)
                ylabel('Rel Disp (m)');
                YS='Rel Disp (m)';
            end            
            
            if(n==2)
                app.THM(:,2)=app.THM(:,2)/25.4;
                app.Z=app.Z/scale;
            end
            if(n==3)
                app.THM(:,2)=app.THM(:,2)*39.37;
                app.Z=app.Z/scale;
            end          
            
            y=app.THM(:,2); 

            c=rainflow(y);
            cycles=c(:,1);
            amp=c(:,2)/2;
  
            rd_Z_ratio = amp/app.Z;
            
            T=app.THM(end,1)-app.THM(1,1);
            
            num=length(amp);
            
            CDI=0;
            
            for i=1:num
            %
                if(rd_Z_ratio(i)>=6)
                    disp(' Failure:  relative displacement exceeds upper limit. ');
                    warndlg(' Failure:  relative displacement exceeds upper limit. ');
                    return
                end
            %
                N=10^(6.05-6.4*log10(rd_Z_ratio(i)));
            %
                d=(cycles(i)/N);    
                
                CDI=CDI+d;
            end
            
            EP=sum(cycles)/T;
            
            out44=sprintf(' Max amp = %8.4g \n',max(amp));
            disp(out44);
            
            out00=sprintf(' Max rd_Z_ratio = %8.4g \n',max(rd_Z_ratio));
            disp(out00);
            
            out11=sprintf(' Duration = %g sec   Cycles=%9.5g \n',T,T*EP);
            disp(out11);
            
            out22=sprintf(' CDI = %8.4g \n',CDI);
            disp(out22);
            
            rate=CDI/T;
            
            out33=sprintf(' Damage Rate = %8.4g per sec\n',rate);
            disp(out33);
            
            tfail=(0.7/rate);
            
            out2=sprintf(' Time to failure = %8.4g sec   Cycles=%9.5g \n',tfail,tfail*EP);
            disp(out2);
            
            h=floor(tfail/3600);
            m=floor((tfail-h*3600)/60);
            s=floor( tfail-h*3600-m*60);
            
            
            string1=sprintf('  %g hr %g min %g sec \n',h,m,s);
            disp(string1)
            
            
            dsec=3600*24;
            ysec=dsec*365;
            
            y=floor(tfail/ysec);
            d=floor((tfail-y*ysec)/dsec);
            
            h=floor((tfail-y*ysec-d*dsec)/3600);
            m=floor((tfail-y*ysec-d*dsec-h*3600)/60);
            s=floor( tfail-y*ysec-d*dsec-h*3600-m*60);
            
            string=sprintf('                 =  %g years %g days %g hr %g min %g sec',y,d,h,m,s);
            disp(string)
            
            Ds=sprintf('%8.4g',CDI);
            

            sss=sprintf('CDI = %8.4g \n\n%s \n%s \n%s',CDI,out33,out2,string1);  

            app.TimeHistoryDamageResultsTextArea.Value=sss;
            app.TimeHistoryDamageResultsTextAreaLabel.Visible='on';
            app.TimeHistoryDamageResultsTextArea.Visible='on';
        end

        % Value changed function: RelDispUnitListBox_TH
        function RelDispUnitListBox_THValueChanged(app, event)
            ChangeDispUnit_TH(app);
            
        end

        % Button pushed function: UpdateListButton_TH
        function UpdateListButton_THPushed(app, event)
            update_TH(app);
        end

        % Value changed function: listbox_method_TH
        function listbox_method_THValueChanged(app, event)

            Items = char(app.listbox_method_TH.Items);
            im=app.listbox_method_TH.Value;
            [app.method_th]=find_index(Items,im);

            if(app.method_th==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_TH(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: CalculateZLimitButton
        function CalculateZLimitButtonPushed(app, event)
            
            Items = strtrim(app.RelativePositionFactorrListBox.Items);
            im = strtrim(app.RelativePositionFactorrListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                r=1;
            end
            if(n==2)
                r=1/sqrt(2);
            end
            if(n==3)
                r=0.5;
            end

            h=str2num(app.hEditField.Value);
            L=str2num(app.LEditField.Value);
            B=str2num(app.BEditField.Value);
            C=str2num(app.CEditField.Value);

            if(app.iu==2)
                B=B/25.4;
                h=h/25.4;
                L=L/25.4;    
            end

            den = C*h*r*sqrt(L);
            
            app.Z=0.00022*B/den;
            
            LU='inch';
            if(app.iu==2) % inch to m
                app.Z=app.Z*25.4;
                LU='mm';
            end
            if(app.iu==3)  % inch to m
                app.Z=app.Z/39.37;
                LU='m';
            end           
            
            zs=sprintf('%7.3g',app.Z);
            zs_shock=sprintf('%7.3g',6*app.Z);

            ss=sprintf('Z for 20 million cycles \n\n %s %s 3-sigma \n\n\n Z for One Shock \n\n %s %s ',zs,LU,zs_shock,LU);

            app.ResultsRelativeDisplacementLimitsTextArea.Value=ss;
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='on';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='on';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';

            Sunits(app);
        end

        % Value changed function: ComponentConstantListBox
        function ComponentConstantListBoxValueChanged(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';          
            CC(app);

        end

        % Value changed function: LibraryListBox
        function LibraryListBoxValueChanged(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            pick(app);
        end

        % Value changing function: CEditField
        function CEditFieldValueChanging(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end

        % Value changed function: RelativePositionFactorrListBox
        function RelativePositionFactorrListBoxValueChanged(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end

        % Value changing function: BEditField
        function BEditFieldValueChanging(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';         
        end

        % Value changing function: hEditField
        function hEditFieldValueChanging(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end

        % Value changing function: LEditField
        function LEditFieldValueChanging(app, event)
            app.ResultsRelativeDisplacementLimitsTextArea.Visible='off';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Visible='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';      
        end

        % Button pushed function: CalculatePVButton
        function CalculatePVButtonPushed(app, event)
            if(app.Z==-999)
                warndlg('Calculate Relative Displacement Limit First');
                return;
            end    

            ff=str2num(app.NaturalFrequencyHzEditField_PV.Value);
            
            % whos ff
            
            omegan=2*pi*ff;

            pv=omegan*app.Z;
            ac=omegan^2*app.Z;
            
            if(app.iu==1)
                sdu='in';
                svu='in/sec';
                ac=ac/386;   
            else
                sdu='mm';
                svu='cm/sec';
                pv=pv/10;
                ac=ac/(1000*9.81);
            end
            
            sau='G';
            
            disp(' ');
            disp(' * * * * * * * * ');
            disp(' ');
            
            s1=sprintf(' Natural Frequency = %g Hz \n',ff);
            
            s2=' 3-sigma Limits for 20 million cycles ';
            
            s3=sprintf(' Relative Displacment %8.4g %s',app.Z,sdu);
            s4=sprintf(' Pseudo Velocity      %8.4g %s',pv,svu);
            s5=sprintf(' Acceleration         %8.4g %s',ac,sau);
            
            s6=' Peak Limits for 1 Shock ';
            
            s7=sprintf(' Relative Displacment %8.4g %s',6*app.Z,sdu);
            s8=sprintf(' Pseudo Velocity      %8.4g %s',6*pv,svu);
            s9=sprintf(' Acceleration         %8.4g %s',6*ac,sau);
            

            ss=sprintf(' %s \n\n %s \n\n %s \n %s \n %s \n\n %s \n\n %s \n %s \n %s ',s1,s2,s3,s4,s5,s6,s7,s8,s9);

            app.ResultsTextArea.Value=ss;    
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Value changing function: NaturalFrequencyHzEditField_PV
        function NaturalFrequencyHzEditField_PVValueChanging(app, event)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
            closereq;
        end

        % Button pushed function: ReturnButton_3
        function ReturnButton_3Pushed(app, event)
            closereq;
        end

        % Button pushed function: ReturnButton_4
        function ReturnButton_4Pushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SteinbergUIFigure = uifigure('Visible', 'off');
            app.SteinbergUIFigure.Name = 'Steinberg';
            app.Steinberg_appver12byTomIrvineLabel = uilabel(app.SteinbergUIFigure);
            app.Steinberg_appver12byTomIrvineLabel.Text = 'Steinberg_app    ver 1.2  by Tom Irvine';
            app.TabGroup = uitabgroup(app.SteinbergUIFigure);
            app.CircuitBoardFatigueZ3sigmaLimitTab = uitab(app.TabGroup);
            app.CircuitBoardFatigueZ3sigmaLimitTab.Title = 'Circuit Board Fatigue Z (3-sigma) Limit';
            app.Label_3 = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.Label_3.Text = 'This script calculates Steinberg''s relative displacement limit for a circuit board.';
            app.ReturnButton = uibutton(app.CircuitBoardFatigueZ3sigmaLimitTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ComponentConstantListBoxLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.ComponentConstantListBoxLabel.Text = 'Component Constant';
            app.ComponentConstantListBox = uilistbox(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.ComponentConstantListBox.Items = {'Manual', 'Library'};
            app.ComponentConstantListBox.ValueChangedFcn = createCallbackFcn(app, @ComponentConstantListBoxValueChanged, true);
            app.ComponentConstantListBox.Value = 'Manual';
            app.CEditFieldLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.CEditFieldLabel.Text = 'C';
            app.LengthofCircuitBoardEdgeParalleltoComponentLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.LengthofCircuitBoardEdgeParalleltoComponentLabel.Text = {'Length of Circuit Board Edge '; 'Parallel to Component'};
            app.CircuitBoardThicknessLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.CircuitBoardThicknessLabel.Text = 'Circuit Board Thickness';
            app.ElectronicComponentLengthLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.ElectronicComponentLengthLabel.Text = 'Electronic Component Length';
            app.CEditField = uieditfield(app.CircuitBoardFatigueZ3sigmaLimitTab, 'text');
            app.CEditField.ValueChangingFcn = createCallbackFcn(app, @CEditFieldValueChanging, true);
            app.LibraryListBoxLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.LibraryListBoxLabel.Text = 'Library';
            app.LibraryListBox = uilistbox(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.LibraryListBox.Items = {'Resistor (axial lead or surface mount)', 'Capacitor (axial lead or surface mount)', 'Diode (axial lead or surface mount)', 'Standard DIP', 'DIP with side-brazed lead wires', 'Through-hole PGA', 'Surface-mounted LCCC with castellations', 'Surface-mounted LCCC with J wires', 'Surface-mounted BGA', 'Fine-pitch surface mount axial alead with bonded corners', 'Any component with two parallel rows of wires'};
            app.LibraryListBox.ValueChangedFcn = createCallbackFcn(app, @LibraryListBoxValueChanged, true);
            app.LibraryListBox.Value = 'Resistor (axial lead or surface mount)';
            app.inLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.inLabel.Text = 'in';
            app.inLabel_2 = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.inLabel_2.Text = 'in';
            app.inLabel_3 = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.inLabel_3.Text = 'in';
            app.UnitsListBoxLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.RelativePositionFactorrListBoxLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.RelativePositionFactorrListBoxLabel.Text = 'Relative Position Factor  r';
            app.RelativePositionFactorrListBox = uilistbox(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.RelativePositionFactorrListBox.Items = {'1.00 Center', '0.707  Half X, Quarter Y', '0.50  Quarter X, Quarter Y'};
            app.RelativePositionFactorrListBox.ValueChangedFcn = createCallbackFcn(app, @RelativePositionFactorrListBoxValueChanged, true);
            app.RelativePositionFactorrListBox.Value = '1.00 Center';
            app.BEditFieldLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.BEditFieldLabel.Text = 'B=';
            app.BEditField = uieditfield(app.CircuitBoardFatigueZ3sigmaLimitTab, 'text');
            app.BEditField.ValueChangingFcn = createCallbackFcn(app, @BEditFieldValueChanging, true);
            app.Image = uiimage(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Steinberg_equation.png');
            app.inch3sigmafor20millioncyclesLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.inch3sigmafor20millioncyclesLabel.Text = 'inch (3-sigma)  for 20 million cycles';
            app.hEditFieldLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.hEditFieldLabel.Text = 'h=';
            app.hEditField = uieditfield(app.CircuitBoardFatigueZ3sigmaLimitTab, 'text');
            app.hEditField.ValueChangingFcn = createCallbackFcn(app, @hEditFieldValueChanging, true);
            app.LEditFieldLabel = uilabel(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.LEditFieldLabel.Text = 'L=';
            app.LEditField = uieditfield(app.CircuitBoardFatigueZ3sigmaLimitTab, 'text');
            app.LEditField.ValueChangingFcn = createCallbackFcn(app, @LEditFieldValueChanging, true);
            app.TabGroup2 = uitabgroup(app.CircuitBoardFatigueZ3sigmaLimitTab);
            app.RelativeDisplacementLimitTab = uitab(app.TabGroup2);
            app.RelativeDisplacementLimitTab.Title = 'Relative Displacement Limit';
            app.ResultsRelativeDisplacementLimitsTextAreaLabel = uilabel(app.RelativeDisplacementLimitTab);
            app.ResultsRelativeDisplacementLimitsTextAreaLabel.Text = 'Results:  Relative Displacement Limits';
            app.ResultsRelativeDisplacementLimitsTextArea = uitextarea(app.RelativeDisplacementLimitTab);
            app.CalculateZLimitButton = uibutton(app.RelativeDisplacementLimitTab, 'push');
            app.CalculateZLimitButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateZLimitButtonPushed, true);
            app.CalculateZLimitButton.Text = 'Calculate Z Limit';
            app.EquivalentPVAccelTab = uitab(app.TabGroup2);
            app.EquivalentPVAccelTab.Title = 'Equivalent PV & Accel';
            app.CalculatePVButton = uibutton(app.EquivalentPVAccelTab, 'push');
            app.CalculatePVButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePVButtonPushed, true);
            app.CalculatePVButton.Text = 'Calculate PV';
            app.ResultsTextAreaLabel = uilabel(app.EquivalentPVAccelTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.EquivalentPVAccelTab);
            app.NaturalFrequencyHzLabel = uilabel(app.EquivalentPVAccelTab);
            app.NaturalFrequencyHzLabel.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField_PV = uieditfield(app.EquivalentPVAccelTab, 'text');
            app.NaturalFrequencyHzEditField_PV.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_PVValueChanging, true);
            app.RelDispPSDTab = uitab(app.TabGroup);
            app.RelDispPSDTab.Title = 'Rel Disp PSD';
            app.Label = uilabel(app.RelDispPSDTab);
            app.Label.Text = {'This script calculates the Palmgren-Miner cumulative fatigue damage from a relative displacement PSD for an electronic circuit board, '; 'using an extension of Steinberg''s empirical data.        The input PSD  array must have two columns:  freq (Hz) & rel disp (unit^2/Hz)'};
            app.CalculatePSDDamageButton = uibutton(app.RelDispPSDTab, 'push');
            app.CalculatePSDDamageButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePSDDamageButtonPushed, true);
            app.CalculatePSDDamageButton.Text = 'Calculate PSD Damage';
            app.listbox_method_psd = uilistbox(app.RelDispPSDTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.RelDispPSDTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.RelDispPSDTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.RelDispPSDTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.RelDispPSDTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.SelectInputArrayListBox.Value = {};
            app.DurationsecEditFieldLabel = uilabel(app.RelDispPSDTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.RelDispPSDTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.RelDispUnitListBoxLabel = uilabel(app.RelDispPSDTab);
            app.RelDispUnitListBoxLabel.Text = 'Rel Disp Unit';
            app.RelDispUnitListBox = uilistbox(app.RelDispPSDTab);
            app.RelDispUnitListBox.Items = {'inch', 'mm', 'm'};
            app.RelDispUnitListBox.ValueChangedFcn = createCallbackFcn(app, @RelDispUnitListBoxValueChanged, true);
            app.RelDispUnitListBox.Value = 'inch';
            app.Zlimitfor20millioncyclesEditFieldLabel = uilabel(app.RelDispPSDTab);
            app.Zlimitfor20millioncyclesEditFieldLabel.Text = 'Z limit for 20 million cycles';
            app.Zlimitfor20millioncyclesEditField = uieditfield(app.RelDispPSDTab, 'text');
            app.Zlimitfor20millioncyclesEditField.ValueChangingFcn = createCallbackFcn(app, @Zlimitfor20millioncyclesEditFieldValueChanging, true);
            app.inch3sigmaLabel = uilabel(app.RelDispPSDTab);
            app.inch3sigmaLabel.Text = 'inch (3-sigma)';
            app.PSDDamageResultsTextAreaLabel = uilabel(app.RelDispPSDTab);
            app.PSDDamageResultsTextAreaLabel.Text = 'PSD Damage Results';
            app.PSDDamageResultsTextArea = uitextarea(app.RelDispPSDTab);
            app.ReturnButton_2 = uibutton(app.RelDispPSDTab, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'Return';
            app.RelDispTimeHistoryTab = uitab(app.TabGroup);
            app.RelDispTimeHistoryTab.Title = 'Rel Disp Time History';
            app.CalculateTimeHistoryDamageButton = uibutton(app.RelDispTimeHistoryTab, 'push');
            app.CalculateTimeHistoryDamageButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTimeHistoryDamageButtonPushed, true);
            app.CalculateTimeHistoryDamageButton.Text = 'Calculate Time History Damage';
            app.TimeHistoryDamageResultsTextAreaLabel = uilabel(app.RelDispTimeHistoryTab);
            app.TimeHistoryDamageResultsTextAreaLabel.Text = 'Time History Damage Results';
            app.TimeHistoryDamageResultsTextArea = uitextarea(app.RelDispTimeHistoryTab);
            app.Label_2 = uilabel(app.RelDispTimeHistoryTab);
            app.Label_2.Text = 'Rainflow cycle counting per ASTM E 1049-85 (2005)      The input array must have two columns: time(sec) & rel disp';
            app.inch3sigmaLabel_TH = uilabel(app.RelDispTimeHistoryTab);
            app.inch3sigmaLabel_TH.Text = 'inch (3-sigma)';
            app.RelDispUnitListBox_2Label = uilabel(app.RelDispTimeHistoryTab);
            app.RelDispUnitListBox_2Label.Text = 'Rel Disp Unit';
            app.RelDispUnitListBox_TH = uilistbox(app.RelDispTimeHistoryTab);
            app.RelDispUnitListBox_TH.Items = {'inch', 'mm', 'm'};
            app.RelDispUnitListBox_TH.ValueChangedFcn = createCallbackFcn(app, @RelDispUnitListBox_THValueChanged, true);
            app.RelDispUnitListBox_TH.Value = 'inch';
            app.Zlimitfor20millioncyclesEditField_2Label = uilabel(app.RelDispTimeHistoryTab);
            app.Zlimitfor20millioncyclesEditField_2Label.Text = 'Z limit for 20 million cycles';
            app.Zlimitfor20millioncyclesEditField_TH = uieditfield(app.RelDispTimeHistoryTab, 'text');
            app.listbox_method_TH = uilistbox(app.RelDispTimeHistoryTab);
            app.listbox_method_TH.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_TH.ValueChangedFcn = createCallbackFcn(app, @listbox_method_THValueChanged, true);
            app.listbox_method_TH.Tag = 'listbox_method';
            app.listbox_method_TH.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.RelDispTimeHistoryTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.UpdateListButton_TH = uibutton(app.RelDispTimeHistoryTab, 'push');
            app.UpdateListButton_TH.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_THPushed, true);
            app.UpdateListButton_TH.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.RelDispTimeHistoryTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_TH = uilistbox(app.RelDispTimeHistoryTab);
            app.SelectInputArrayListBox_TH.Items = {'Item 1'};
            app.ReturnButton_3 = uibutton(app.RelDispTimeHistoryTab, 'push');
            app.ReturnButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_3Pushed, true);
            app.ReturnButton_3.Text = 'Return';
            app.CircuitBoardDampingTab = uitab(app.TabGroup);
            app.CircuitBoardDampingTab.Title = 'Circuit Board Damping';
            app.CalculateDampingButton = uibutton(app.CircuitBoardDampingTab, 'push');
            app.CalculateDampingButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDampingButtonPushed, true);
            app.CalculateDampingButton.Text = 'Calculate Damping';
            app.StructureListBoxLabel = uilabel(app.CircuitBoardDampingTab);
            app.StructureListBoxLabel.Text = 'Structure';
            app.StructureListBox = uilistbox(app.CircuitBoardDampingTab);
            app.StructureListBox.Items = {'Beam-type Structure', 'Perimeter-supported PCB', 'Small Electronic Chassis'};
            app.StructureListBox.ValueChangedFcn = createCallbackFcn(app, @StructureListBoxValueChanged, true);
            app.StructureListBox.Value = 'Beam-type Structure';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.CircuitBoardDampingTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.CircuitBoardDampingTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.BaseInputSineGEditFieldLabel = uilabel(app.CircuitBoardDampingTab);
            app.BaseInputSineGEditFieldLabel.Text = 'Base Input Sine (G)';
            app.BaseInputSineGEditField = uieditfield(app.CircuitBoardDampingTab, 'text');
            app.BaseInputSineGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseInputSineGEditFieldValueChanging, true);
            app.ExcitationListBoxLabel = uilabel(app.CircuitBoardDampingTab);
            app.ExcitationListBoxLabel.Text = 'Excitation';
            app.ExcitationListBox = uilistbox(app.CircuitBoardDampingTab);
            app.ExcitationListBox.Items = {'Sine', 'Random'};
            app.ExcitationListBox.ValueChangedFcn = createCallbackFcn(app, @ExcitationListBoxValueChanged, true);
            app.ExcitationListBox.Value = 'Sine';
            app.DampingfromSteinbergsempiricalformulaLabel = uilabel(app.CircuitBoardDampingTab);
            app.DampingfromSteinbergsempiricalformulaLabel.Text = 'Damping from Steinberg''s empirical formula.';
            app.DampingResultsTextAreaLabel = uilabel(app.CircuitBoardDampingTab);
            app.DampingResultsTextAreaLabel.Text = 'Damping Results';
            app.DampingResultsTextArea = uitextarea(app.CircuitBoardDampingTab);
            app.ReturnButton_4 = uibutton(app.CircuitBoardDampingTab, 'push');
            app.ReturnButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_4Pushed, true);
            app.ReturnButton_4.Text = 'Return';
            % Show the figure after all components are created
            app.SteinbergUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Steinberg_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SteinbergUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SteinbergUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SteinbergUIFigure)
        end
    end
end