classdef Strouhal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StrouhalUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculateStrouhalNumberTab      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        FreestreamVelocityEditField     matlab.ui.control.EditField
        FreestreamVelocityEditFieldLabel  matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.EditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        VortexFrequencyHzEditField      matlab.ui.control.EditField
        VortexFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StrouhalNumberEditField_2       matlab.ui.control.EditField
        StrouhalNumberEditField_2Label  matlab.ui.control.Label
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        LengthUnitListBox               matlab.ui.control.ListBox
        LengthUnitListBoxLabel          matlab.ui.control.Label
        CalculateListBox                matlab.ui.control.ListBox
        CalculateListBoxLabel           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        StrouhalReynoldsNumberCorrelationTab  matlab.ui.container.Tab
        StrouhalNumberEditField         matlab.ui.control.EditField
        StrouhalNumberEditFieldLabel    matlab.ui.control.Label
        EnterReynoldsNumberEditField    matlab.ui.control.EditField
        EnterReynoldsNumberEditFieldLabel  matlab.ui.control.Label
        ReferenceRBlevinsFluidDynamicsHandbook1984Label  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        Strouhalver10byTomIrvineLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        na % Description
        iu %
    end
    
    methods (Access = private)
        
        function Change(app)
            Clear(app);

            app.StrouhalNumberEditField_2.Visible='on';
            app.VortexFrequencyHzEditField.Visible='on';
            app.DiameterEditField.Visible='on';
            app.FreestreamVelocityEditField.Visible='on';

            app.StrouhalNumberEditField_2Label.Visible='on';
            app.VortexFrequencyHzEditFieldLabel.Visible='on';
            app.DiameterEditFieldLabel.Visible='on';
            app.FreestreamVelocityEditFieldLabel.Visible='on';            

            Items = char(app.CalculateListBox.Items);
            im=app.CalculateListBox.Value;
            [app.na]=find_index(Items,im);

            if(app.na==1) % Strouhal
                app.StrouhalNumberEditField_2.Visible='off';
                app.StrouhalNumberEditField_2Label.Visible='off';
            end    
            if(app.na==2) % Vortex Frequency
                app.VortexFrequencyHzEditField.Visible='off';
 		        app.VortexFrequencyHzEditFieldLabel.Visible='off';
            end
            if(app.na==3) % Diameter
		        app.DiameterEditField.Visible='off';
		        app.DiameterEditFieldLabel.Visible='off';
            end               
            if(app.na==4) % Freestream Velocity
                app.FreestreamVelocityEditField.Visible='off';
                app.FreestreamVelocityEditFieldLabel.Visible='off';   
            end    

            Items = char(app.LengthUnitListBox.Items);
            im=app.LengthUnitListBox.Value;
            [app.iu]=find_index(Items,im);

            if(app.iu==1)
                app.DiameterEditFieldLabel.Text='Diameter (ft)';
                app.FreestreamVelocityEditFieldLabel.Text='Freestream Velocity (ft/sec)';
            end
            if(app.iu==2)
                app.DiameterEditFieldLabel.Text='Diameter (in)';
                app.FreestreamVelocityEditFieldLabel.Text='Freestream Velocity (in/sec)';             
            end    
            if(app.iu==3)                
                app.DiameterEditFieldLabel.Text='Diameter (m)';
                app.FreestreamVelocityEditFieldLabel.Text='Freestream Velocity (m/sec)';             
            end  
            
            
        end
        function Clear(app)
            app.ResultsEditField.Visible='off';
            app.ResultsEditFieldLabel.Visible='off';            
            app.StrouhalNumberEditFieldLabel.Visible='off';
            app.StrouhalNumberEditField.Visible='off';              
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Change(app);      
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            R=str2num(app.EnterReynoldsNumberEditField.Value);

            x=[30 50 500 1000 1e4 1e5 1e6 1e7];
            y=[0 0.13 0.20 0.21 0.20 0.19 0.21 0.23];

            n=length(x);

            iflag=0;
            if(R<=x(1))
                S=0;
                iflag=1;
            end
            if(R>x(end))
                S=y(end);
                iflag=1;
            end  
            if(iflag==0)
                for i=1:n-1
                    if(R>=x(i) && R<=x(i+1))
                        [S]=linear_interpolation_function(x(i),y(i),x(i+1),y(i+1),R);
                    end    
                end
            end    

            app.StrouhalNumberEditField.Value=sprintf('%8.4g',S);
            app.StrouhalNumberEditFieldLabel.Visible='on';
            app.StrouhalNumberEditField.Visible='on';
        end

        % Value changing function: EnterReynoldsNumberEditField
        function EnterReynoldsNumberEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changed function: CalculateListBox
        function CalculateListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: LengthUnitListBox
        function LengthUnitListBoxValueChanged(app, event)
            Change(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.na==1) % Strouhal
                f=str2num(app.VortexFrequencyHzEditField.Value);
                d=str2num(app.DiameterEditField.Value);
                u=str2num(app.FreestreamVelocityEditField.Value);
                app.ResultsEditFieldLabel.Text='Strouhal Number';
                try
                    Q=f*d/u;
                catch
                    warndlg('Calculation failed.  Check input parameters');
                    return;
                end    
            end    
            if(app.na==2) % Vortex Frequency
                s=str2num(app.StrouhalNumberEditField_2.Value);
                d=str2num(app.DiameterEditField.Value);
                u=str2num(app.FreestreamVelocityEditField.Value);
                app.ResultsEditFieldLabel.Text='Frequency (Hz)';  
                try
                    Q=s*u/d;
                catch
                    warndlg('Calculation failed.  Check input parameters');
                    return;
                end                  
            end
            if(app.na==3) % Diameter
                s=str2num(app.StrouhalNumberEditField_2.Value);
                f=str2num(app.VortexFrequencyHzEditField.Value);
                u=str2num(app.FreestreamVelocityEditField.Value);
                if(app.iu==1)
                    app.ResultsEditFieldLabel.Text='Diameter (ft)';
                end
                if(app.iu==2)
                    app.ResultsEditFieldLabel.Text='Diameter (in)';
                end
                if(app.iu==3)
                    app.ResultsEditFieldLabel.Text='Diameter (m)';
                end    
                try
                    Q=s*u/f;
                catch
                    warndlg('Calculation failed.  Check input parameters');
                    return;
                end                 
            end               
            if(app.na==4) % Freestream Velocity
                s=str2num(app.StrouhalNumberEditField_2.Value);
                f=str2num(app.VortexFrequencyHzEditField.Value);
                d=str2num(app.DiameterEditField.Value);
                if(app.iu==1)
                    app.ResultsEditFieldLabel.Text='Velocity (ft/sec)';
                end
                if(app.iu==2)
                    app.ResultsEditFieldLabel.Text='Velocity (in/sec)';
                end
                if(app.iu==3)
                    app.ResultsEditFieldLabel.Text='Velocity (m/sec)';
                end 
                try
                    Q=f*d/s;
                catch
                    warndlg('Calculation failed.  Check input parameters');
                    return;
                end                
            end 

            sq=sprintf('%8.4g',Q);
            app.ResultsEditField.Value=sq;            
            app.ResultsEditField.Visible='on';
            app.ResultsEditFieldLabel.Visible='on';  

        end

        % Value changing function: StrouhalNumberEditField_2
        function StrouhalNumberEditField_2ValueChanging(app, event)
 
            Clear(app);
        end

        % Value changing function: VortexFrequencyHzEditField
        function VortexFrequencyHzEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: DiameterEditField
        function DiameterEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: FreestreamVelocityEditField
        function FreestreamVelocityEditFieldValueChanging(app, event)
            Clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StrouhalUIFigure = uifigure('Visible', 'off');
            app.StrouhalUIFigure.Name = 'Strouhal';
            app.Strouhalver10byTomIrvineLabel = uilabel(app.StrouhalUIFigure);
            app.Strouhalver10byTomIrvineLabel.Text = {'Strouhal   ver 1.0   by Tom Irvine   '; ''; 'The Strouhal number is the nondimensional frequency of vortex shedding in vortex-induced vibration '};
            app.ReturnButton = uibutton(app.StrouhalUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.StrouhalUIFigure);
            app.CalculateStrouhalNumberTab = uitab(app.TabGroup);
            app.CalculateStrouhalNumberTab.Title = 'Calculate Strouhal Number';
            app.CalculateButton = uibutton(app.CalculateStrouhalNumberTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CalculateListBoxLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.CalculateListBoxLabel.Text = 'Calculate';
            app.CalculateListBox = uilistbox(app.CalculateStrouhalNumberTab);
            app.CalculateListBox.Items = {'Strouhal Number', 'Vortex Frequency', 'Diameter', 'Freestream Velocity'};
            app.CalculateListBox.ValueChangedFcn = createCallbackFcn(app, @CalculateListBoxValueChanged, true);
            app.CalculateListBox.Value = 'Strouhal Number';
            app.LengthUnitListBoxLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.LengthUnitListBoxLabel.Text = 'Length Unit';
            app.LengthUnitListBox = uilistbox(app.CalculateStrouhalNumberTab);
            app.LengthUnitListBox.Items = {'feet', 'inch', 'meters'};
            app.LengthUnitListBox.ValueChangedFcn = createCallbackFcn(app, @LengthUnitListBoxValueChanged, true);
            app.LengthUnitListBox.Value = 'feet';
            app.ResultsEditFieldLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.CalculateStrouhalNumberTab, 'text');
            app.StrouhalNumberEditField_2Label = uilabel(app.CalculateStrouhalNumberTab);
            app.StrouhalNumberEditField_2Label.Text = 'Strouhal Number';
            app.StrouhalNumberEditField_2 = uieditfield(app.CalculateStrouhalNumberTab, 'text');
            app.StrouhalNumberEditField_2.ValueChangingFcn = createCallbackFcn(app, @StrouhalNumberEditField_2ValueChanging, true);
            app.VortexFrequencyHzEditFieldLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.VortexFrequencyHzEditFieldLabel.Text = 'Vortex Frequency (Hz)';
            app.VortexFrequencyHzEditField = uieditfield(app.CalculateStrouhalNumberTab, 'text');
            app.VortexFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @VortexFrequencyHzEditFieldValueChanging, true);
            app.DiameterEditFieldLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.CalculateStrouhalNumberTab, 'text');
            app.DiameterEditField.ValueChangingFcn = createCallbackFcn(app, @DiameterEditFieldValueChanging, true);
            app.FreestreamVelocityEditFieldLabel = uilabel(app.CalculateStrouhalNumberTab);
            app.FreestreamVelocityEditFieldLabel.Text = 'Freestream Velocity';
            app.FreestreamVelocityEditField = uieditfield(app.CalculateStrouhalNumberTab, 'text');
            app.FreestreamVelocityEditField.ValueChangingFcn = createCallbackFcn(app, @FreestreamVelocityEditFieldValueChanging, true);
            app.Label = uilabel(app.CalculateStrouhalNumberTab);
            app.Label.Text = {'Note that the Strouhal number '; 'is approximately 2 for circular '; 'cross-sections.'};
            app.StrouhalReynoldsNumberCorrelationTab = uitab(app.TabGroup);
            app.StrouhalReynoldsNumberCorrelationTab.Title = 'Strouhal & Reynolds Number Correlation';
            app.CalculateButton_2 = uibutton(app.StrouhalReynoldsNumberCorrelationTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ReferenceRBlevinsFluidDynamicsHandbook1984Label = uilabel(app.StrouhalReynoldsNumberCorrelationTab);
            app.ReferenceRBlevinsFluidDynamicsHandbook1984Label.Text = {'Reference:  R. Blevins, Fluid Dynamics Handbook, Van Norstrand Reinhold, New York,1984  '; ''; 'Assume circular cross-section'};
            app.EnterReynoldsNumberEditFieldLabel = uilabel(app.StrouhalReynoldsNumberCorrelationTab);
            app.EnterReynoldsNumberEditFieldLabel.Text = 'Enter Reynolds Number';
            app.EnterReynoldsNumberEditField = uieditfield(app.StrouhalReynoldsNumberCorrelationTab, 'text');
            app.EnterReynoldsNumberEditField.ValueChangingFcn = createCallbackFcn(app, @EnterReynoldsNumberEditFieldValueChanging, true);
            app.StrouhalNumberEditFieldLabel = uilabel(app.StrouhalReynoldsNumberCorrelationTab);
            app.StrouhalNumberEditFieldLabel.Text = 'Strouhal Number';
            app.StrouhalNumberEditField = uieditfield(app.StrouhalReynoldsNumberCorrelationTab, 'text');
            app.StrouhalNumberEditField.Editable = 'off';
            % Show the figure after all components are created
            app.StrouhalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Strouhal

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StrouhalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.StrouhalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StrouhalUIFigure)
        end
    end
end