classdef accel_vel_disp_correction_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        pushbutton_info         matlab.ui.control.Button
        text17                  matlab.ui.control.Label
        uipanel1                matlab.ui.container.Panel
        text_fc                 matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        listbox_taper_a         matlab.ui.control.ListBox
        listbox_integration_3   matlab.ui.control.ListBox
        text12                  matlab.ui.control.Label
        listbox_integration_2   matlab.ui.control.ListBox
        text11                  matlab.ui.control.Label
        listbox_integration_1   matlab.ui.control.ListBox
        text10                  matlab.ui.control.Label
        listbox_units           matlab.ui.control.ListBox
        text9                   matlab.ui.control.Label
        edit_input_array_name   matlab.ui.control.EditField
        text_input_array_name   matlab.ui.control.Label
        listbox_method          matlab.ui.control.ListBox
        text7                   matlab.ui.control.Label
        listbox_psave           matlab.ui.control.ListBox
        text6                   matlab.ui.control.Label
        text5                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        listbox_taper_v         matlab.ui.control.ListBox
        text3                   matlab.ui.control.Label
        listbox_taper_d         matlab.ui.control.ListBox
        uipanel_save            matlab.ui.container.Panel
        pushbutton4             matlab.ui.control.Button
        text16                  matlab.ui.control.Label
        edit_output_array_name  matlab.ui.control.EditField
        pushbutton_save         matlab.ui.control.Button
        text15                  matlab.ui.control.Label
        listbox_output          matlab.ui.control.ListBox
        pushbutton_return       matlab.ui.control.Button
        pushbutton_calculate    matlab.ui.control.Button
        edit_fc                 matlab.ui.control.EditField
        text2                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function listbox_integration(app)
            
            app.uipanel_save.Visible='off';
            
            n=strtrim(app.listbox_integration_1.Value);
            
            if(contains(n,'Highpass'))
                app.edit_fc.Visible='on';
                app.text_fc.Visible='on';
            else
                app.edit_fc.Visible='off';
                app.text_fc.Visible='off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function accel_vel_disp_correction_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to accel_vel_disp_correction (see VARARGIN)
            
            % Choose default command line output for accel_vel_disp_correction
            handles.output = hObject;
            
            set(handles.listbox_method,'Value',1);

            listbox_integration(app);
            
            
            set(handles.edit_input_array_name,'Visible','on');
            set(handles.text_input_array_name,'Visible','on');
            set(handles.edit_input_array_name,'Enable','on');
            set(handles.text_input_array_name,'Enable','on');
            
            
            set(handles.listbox_integration_1,'Value',1);
            set(handles.listbox_integration_2,'Value',1);
            set(handles.listbox_integration_3,'Value',1);
            
            set(handles.uipanel_save,'Visible','off');
            
            set(handles.listbox_psave,'Value',2);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_integration_1
        function listbox_integration_1_Callback(app, event)
            listbox_integration(app);
        end

        % Value changed function: listbox_method
        function listbox_method_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_method (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_method contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_method
            
            set(handles.uipanel_save,'Visible','off');
            
            n=get(handles.listbox_method,'Value');
            
            set(handles.text_input_array_name,'Visible','on');
            set(handles.edit_input_array_name,'Visible','on');
            
            if(n==1)
               set(handles.edit_input_array_name,'enable','on')
            else
            
               set(handles.text_input_array_name,'Visible','off');
               set(handles.edit_input_array_name,'Visible','off');
            
               set(handles.edit_input_array_name,'enable','off')
            
               [filename, pathname] = uigetfile('*.*');
               filename = fullfile(pathname, filename);
               fid = fopen(filename,'r');
               THM = fscanf(fid,'%g %g',[2 inf]);
               THM=THM';
            
               setappdata(0,'THM',THM);
            end
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end
            
            fprintf('\n * * * * * * * \n');
            
            
            iunit=get(handles.listbox_units,'Value');
            setappdata(0,'iunit',iunit);
            
            k=get(handles.listbox_method,'Value');
            
            try
            
            if(k==1)
              FS=get(handles.edit_input_array_name,'String');
              THM=evalin('base',FS);
            else
              THM=getappdata(0,'THM');
            end
            
            catch
                warndlg('Input Array not found');
                return;
            end
            
            tt=THM(:,1);
            
            y=double(THM(:,2));
            
            num=length(y);
            
            dur=THM(num,1)-THM(1,1);
            
            dt=dur/(num-1);
            
            %%%
            
            I1=get(handles.listbox_integration_1,'Value');
            I2=get(handles.listbox_integration_2,'Value');
            I3=get(handles.listbox_integration_3,'Value');
            
            %%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 1
            
            if(I1==2)
                y=y-mean(y);
            end
            if(I1==3)
                y=detrend(y);
            end
            if(I1==4)
                disp(' % hp filter')
                fc=str2num(get(handles.edit_fc,'String'))
                y=detrend(y);
                % old-school method is more stable than Matlab's filter
                [y]=Butterworth_filter_highpass_function(y,fc,dt);
            end
            
            ntap=get(handles.listbox_taper_a,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.1;
                end
                if(ntap==3)
                    npe=0.5;
                end
                if(ntap==4)
                    npe=1;
                end
                if(ntap==5)
                    npe=2;
                end
                if(ntap==6)
                    npe=3;
                end
                if(ntap==7)
                    npe=4;
                end
                if(ntap==8)
                    npe=5;
                end
                if(ntap==9)
                    npe=6;
                end
                if(ntap==10)
                    npe=7;
                end
                if(ntap==11)
                    npe=8;
                end
                if(ntap==12)
                    npe=9;
                end
                if(ntap==13)
                    npe=10;
                end
            %
                [y]=half_cosine_fade_perc(y,npe);
            
            end
            
            a=y;
            
            v=zeros(num,1);
            v(1)=y(1)*dt/2;
            
            for i=2:(num-1)
                v(i)=v(i-1)+y(i)*dt;
            end
            v(num)=v(num-1)+y(num)*dt/2;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % In Between
            
            if(I2==2)
                v=v-mean(v);
            end
            if(I2==3)
                v=detrend(v);
            end
            if(I2==4)
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            
            ntap=get(handles.listbox_taper_v,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.1;
                end
                if(ntap==3)
                    npe=0.5;
                end
                if(ntap==4)
                    npe=1;
                end
                if(ntap==5)
                    npe=2;
                end
                if(ntap==6)
                    npe=3;
                end
                if(ntap==7)
                    npe=4;
                end
                if(ntap==8)
                    npe=5;
                end
                if(ntap==9)
                    npe=6;
                end
                if(ntap==10)
                    npe=7;
                end
                if(ntap==11)
                    npe=8;
                end
                if(ntap==12)
                    npe=9;
                end
                if(ntap==13)
                    npe=10;
                end
            %
                [v]=half_cosine_fade_perc(v,npe);
            
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2
            
            d=zeros(num,1);
            d(1)=v(1)*dt/2;
            
            for i=2:(num-1)
                d(i)=d(i-1)+v(i)*dt;
            end
            d(num)=d(num-1)+v(num)*dt/2;
            
            d=fix_size(d);
            
            if(I3==2)
                d=d-mean(d);
            end
            if(I3==3)
                d=detrend(d);
            end
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            ntap=get(handles.listbox_taper_d,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.1;
                end
                if(ntap==3)
                    npe=0.5;
                end
                if(ntap==4)
                    npe=1;
                end
                if(ntap==5)
                    npe=2;
                end
                if(ntap==6)
                    npe=3;
                end
                if(ntap==7)
                    npe=4;
                end
                if(ntap==8)
                    npe=5;
                end
                if(ntap==9)
                    npe=6;
                end
                if(ntap==10)
                    npe=7;
                end
                if(ntap==11)
                    npe=8;
                end
                if(ntap==12)
                    npe=9;
                end
                if(ntap==13)
                    npe=10;
                end
            %
                [d]=half_cosine_fade_perc(d,npe);
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (ips)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/s^2)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            [vv]=differentiate_function(d,dt);
            [aa]=differentiate_function(vv,dt);
            
            if(iunit==1) % G, in/sec, in
                aa=aa/386;
            end
            if(iunit==2) % G, cm/sec, mm
                vv=vv/10;
                aa=aa/(9.81*1000);
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vv=vv/10;
                aa=aa/1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            vvv=zeros(num,1);
            vvv(1)=aa(1)*dt/2;
            
            for i=2:(num-1)
                vvv(i)=vvv(i-1)+aa(i)*dt;
            end
            vvv(num)=vvv(num-1)+aa(num)*dt/2;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ddd=zeros(num,1);
            ddd(1)=vvv(1)*dt/2;
            
            for i=2:(num-1)
                ddd(i)=ddd(i-1)+vvv(i)*dt;
            end
            ddd(num)=ddd(num-1)+vvv(num)*dt/2;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iunit==1) % G, in/sec, in
                vvv=vvv*386;
                ddd=ddd*386;
            end
            if(iunit==2) % G, cm/sec, mm
                vvv=vvv*9.81*100;
                ddd=ddd*9.81*1000;
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vvv=vvv*10;
                ddd=ddd*1000;
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a1=max(abs(a));
            a2=max(abs(a));
            amax=max([a1 a2 ]);
            
            [ya]=ytick_linear_altp(amax);
            
            v1=max(abs(v));
            v2=max(abs(vv));
            v3=max(abs(vvv));
            vmax=max([v1 v2 v3]);
            [yv]=ytick_linear_altp(vmax);
            
            d1=max(abs(d));
            d3=max(abs(ddd));
            dmax=max([d1  d3]);
            [yd]=ytick_linear_altp(dmax);
            
            
            fig_num=1;
            
            tstart=tt(1);
            tend=1.001*tt(num);
            
            
            hp=figure(fig_num);
            fig_num=fig_num+1;
            subplot(3,3,1);
            plot(tt,a);
            ylabel(ay);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,2);
            plot(tt,aa);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,3);
            plot(tt,aa);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,4);
            plot(tt,v);
            ylabel(vy);
            xlim([tstart tend]);
            ylim([-yv(1) yv(1)]);
            grid on;
            
            subplot(3,3,5);
            plot(tt,vv);
            ylim([-yv(1) yv(1)]);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,6);
            plot(tt,vvv);
            xlim([tstart tend]);
            ylim([-yv(1) yv(1)]);
            grid on;
            
            subplot(3,3,7);
            plot(tt,d);
            xlim([tstart tend]);
            ylabel(dy);
            ylim([-yd(1) yd(1)]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,8);
            plot(tt,d);
            ylim([-yd(1) yd(1)]);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,9);
            plot(tt,ddd);
            ylim([-yd(1) yd(1)]);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            
            set(hp, 'Position', [0 0 850 650]);


            %%%
            
            psave=get(handles.listbox_psave,'Value');
            
            
            if(psave==1)
            
                pname='integrated_data';
            
            
                set(gca,'Fontsize',12);
                print(hp,pname,'-dpng','-r300');
            
                out1=sprintf('\n Plot File:  %s.png',pname);
                disp(out1);
            
                msgbox('Plot file exported to hard drive: integrated_data.png');
            
            end
            
            
            %%%
            
            set(handles.uipanel_save,'Visible','on');
            
            tt=fix_size(tt);
            aa=fix_size(aa);
            vvv=fix_size(vvv);
            ddd=fix_size(ddd);
            
            aaa=[tt aa];
            vvv=[tt vvv];
            ddd=[tt ddd];
            
            setappdata(0,'acceleration',aaa);
            setappdata(0,'velocity',vvv);
            setappdata(0,'displacement',ddd);
            setappdata(0,'dt',dt);
            setappdata(0,'ay',ay);
            setappdata(0,'vy',vy);
            setappdata(0,'dy',dy);
            
            disp(' ');
                    disp('                 max     min     std ')
            
            out1=sprintf(' acceleration: %8.4g %8.4g %8.4g ',max(a),min(a),std(a));
            out2=sprintf('     velocity: %8.4g %8.4g %8.4g ',max(v),min(v),std(v));
            out3=sprintf(' displacement: %8.4g %8.4g %8.4g  ',max(d),min(d),std(d));
            
            disp(out1);
            disp(out2);
            disp(out3);
            
            setappdata(0,'fig_num',fig_num);
        end

        % Button pushed function: pushbutton_info
        function pushbutton_info_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_info (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            msgbox(' Description written to Matlab Command Window ');
            
            disp(' ');
            disp(' ');
            disp(' The goal of this script is to correct an acceleration signal so ');
            disp(' that its integrated velocity and double integrated displacement ');
            disp(' each oscillates about its respective zero baseline. ');
            disp(' ');
            disp(' The user must use trial-and-error to determine the best ');
            disp(' combination of filtering, trend removal and tapering to ');
            disp(' to achieve this goal.');
            disp(' ');
            disp(' Nine subplots appear as follows: ');
            disp(' ');
            disp(' Row 1, Col 1 - processed acceleration prior to integration ');
            disp(' Row 2, Col 1 - integrated velocity ');
            disp(' Row 3, Col 1 - double integrated displacement ');
            disp(' ');
            disp(' Row 3, Col 2 - double integrated displacement (repeated) ');
            disp(' Row 2, Col 2 - differentiated velocity from double integrated displacement');
            disp(' Row 1, Col 2 - double differentiated acceleration from double integrated displacement');
            disp(' ');
            disp(' Row 1, Col 3 - double differentiated acceleration (repeated) ');
            disp(' Row 2, Col 3 - integrated velocity from double differentiated acceleration');
            disp(' Row 3, Col 3 - double integrated displacement from double differentiated acceleration');
            disp(' ');
            disp(' The goal is that the curves in all nine plots oscillate about the');
            disp(' respective zero baseline. ');
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_output,'Value');
            
            if(n==1)
                data=getappdata(0,'acceleration');
            end
            if(n==2)
                data=getappdata(0,'velocity');
            end
            if(n==3)
                data=getappdata(0,'displacement');
            end
            
            output_name=get(handles.edit_output_array_name,'String');
            
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete');
        end

        % Button pushed function: pushbutton_return
        function pushbutton_returnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.s=vibrationdata_differentiate;
            set(handles.s,'Visible','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'accel_vel_disp_correction';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'accel_vel_disp_correction.m  ver 1.4  by Tom Irvine';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.edit_fc = uieditfield(app.uipanel1, 'text');
            app.edit_fc.Tag = 'edit_fc';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_returnButtonPushed, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel_save = uipanel(app.uipanel1);
            app.uipanel_save.Title = 'Save';
            app.uipanel_save.Tag = 'uipanel_save';
            app.listbox_output = uilistbox(app.uipanel_save);
            app.listbox_output.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.listbox_output.Tag = 'listbox_output';
            app.listbox_output.Value = 'Acceleration';
            app.text15 = uilabel(app.uipanel_save);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Select Output';
            app.pushbutton_save = uibutton(app.uipanel_save, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.edit_output_array_name = uieditfield(app.uipanel_save, 'text');
            app.edit_output_array_name.Tag = 'edit_output_array_name';
            app.text16 = uilabel(app.uipanel_save);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Output Array Name';
            app.pushbutton4 = uibutton(app.uipanel_save, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Differentiate Displacement';
            app.listbox_taper_d = uilistbox(app.uipanel1);
            app.listbox_taper_d.Items = {'None', '0.1', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_d.Tag = 'listbox_taper_d';
            app.listbox_taper_d.Value = 'None';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Duration Percent Taper';
            app.listbox_taper_v = uilistbox(app.uipanel1);
            app.listbox_taper_v.Items = {'None', '0.1', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_v.Tag = 'listbox_taper_v';
            app.listbox_taper_v.Value = 'None';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Velocity';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Displacement';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Export plot to png';
            app.listbox_psave = uilistbox(app.uipanel1);
            app.listbox_psave.Items = {'yes', 'no'};
            app.listbox_psave.Tag = 'listbox_psave';
            app.listbox_psave.Value = 'yes';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.uipanel1);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_method_Callback, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text_input_array_name = uilabel(app.uipanel1);
            app.text_input_array_name.Tag = 'text_input_array_name';
            app.text_input_array_name.Text = 'Input Array Name';
            app.edit_input_array_name = uieditfield(app.uipanel1, 'text');
            app.edit_input_array_name.Tag = 'edit_input_array_name';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Select Units';
            app.listbox_units = uilistbox(app.uipanel1);
            app.listbox_units.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'G, in/sec, in';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Select Trend Removal Prior to Integration of Acceleration';
            app.listbox_integration_1 = uilistbox(app.uipanel1);
            app.listbox_integration_1.Items = {'None', 'Mean Removal', 'First Order Trend', 'Highpass Filter'};
            app.listbox_integration_1.ValueChangedFcn = createCallbackFcn(app, @listbox_integration_1_Callback, true);
            app.listbox_integration_1.Tag = 'listbox_integration_1';
            app.listbox_integration_1.Value = 'None';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Select Trend Removal Prior to Integration of Velocity';
            app.listbox_integration_2 = uilistbox(app.uipanel1);
            app.listbox_integration_2.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.listbox_integration_2.Tag = 'listbox_integration_2';
            app.listbox_integration_2.Value = 'None';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Select Trend Removal after Integration to Velocity';
            app.listbox_integration_3 = uilistbox(app.uipanel1);
            app.listbox_integration_3.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.listbox_integration_3.Tag = 'listbox_integration_3';
            app.listbox_integration_3.Value = 'None';
            app.listbox_taper_a = uilistbox(app.uipanel1);
            app.listbox_taper_a.Items = {'None', '0.1', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_a.Tag = 'listbox_taper_a';
            app.listbox_taper_a.Value = 'None';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Acceleration';
            app.text_fc = uilabel(app.uipanel1);
            app.text_fc.Tag = 'text_fc';
            app.text_fc.Text = 'Enter Highpass Frequency (Hz)';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'The input file must have two columns:  time(sec) & acceleration.';
            app.pushbutton_info = uibutton(app.figure1, 'push');
            app.pushbutton_info.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_info_Callback, true);
            app.pushbutton_info.Tag = 'pushbutton_info';
            app.pushbutton_info.Text = 'Script Information';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accel_vel_disp_correction_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)accel_vel_disp_correction_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end