classdef accidental_drop_shock_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        accidentaldropshockUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AssumptionsTab                  matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        AccelerationTab                 matlab.ui.container.Tab
        fnAccelListBox                  matlab.ui.control.ListBox
        NaturalFrequencyAccelEditField  matlab.ui.control.EditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        HeightAccelListBox              matlab.ui.control.ListBox
        DropHeightAccelEditField        matlab.ui.control.EditField
        DropHeightEditFieldLabel        matlab.ui.control.Label
        CalculateButton_acceleration    matlab.ui.control.Button
        DropHeightTab                   matlab.ui.container.Tab
        PeakAccelerationHeightEditField  matlab.ui.control.EditField
        PeakAccelerationEditFieldLabel  matlab.ui.control.Label
        AccelHeightListBox              matlab.ui.control.ListBox
        NaturalFrequencyHeightEditField  matlab.ui.control.EditField
        NaturalFrequencyEditFieldLabel_2  matlab.ui.control.Label
        fnHeightListBox                 matlab.ui.control.ListBox
        CalculateButton_drop_height     matlab.ui.control.Button
        NaturalFrequencyTab             matlab.ui.container.Tab
        PeakAccelerationfnEditField     matlab.ui.control.EditField
        PeakAccelerationEditFieldLabel_2  matlab.ui.control.Label
        AccelfnListBox                  matlab.ui.control.ListBox
        HeightfnEditField               matlab.ui.control.EditField
        DropHeightEditFieldLabel_2      matlab.ui.control.Label
        HeightfnListBox                 matlab.ui.control.ListBox
        CalculateButton_fn              matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        PeakResponseValuesTextArea      matlab.ui.control.TextArea
        PeakResponseValuesTextAreaLabel  matlab.ui.control.Label
        NaturalFrequencyTextArea        matlab.ui.control.TextArea
        NaturalFrequencyTextAreaLabel   matlab.ui.control.Label
        DropHeightTextArea              matlab.ui.control.TextArea
        DropHeightTextAreaLabel         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        CalculateButton                 matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        accidental_drop_shock_aver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        meters_per_inch
        inches_per_meters
        
        meters_per_feet
        feet_per_meters

            a_G
            a_ipss
            a_ftpss 
            
            sd_m
            sd_in
            sd_ft
            sd_mm

            v_mps
            v_ips
            v_fps     

            h_in
            h_ft
            h_m

            a_mpss

            tpi
            fn
            omegan

            G

            n_freq
            bfn
            n_height
            bH
            n_accel
            bA

    end
    
    methods (Access = private)

        function calculate_accel(app)

            a=app.bA;

            if(app.n_accel==1) % G
                app.a_G=a;
                app.a_ipss=a*386.;
                app.a_ftpss=a*32.2;
                app.a_mpss=a*9.81;
            end    
            if(app.n_accel==2) % in/sec^2
                app.a_ipss=a;
                app.a_G=a/386.;
                app.a_ftpss=a/12;
                app.a_mpss=a*app.meters_per_inch;        
            end    
            if(app.n_accel==3) % ft/sec^2
                app.a_ftpss=a;  
                app.a_G=a/32.2;
                app.a_ipss=a*12;
                app.a_mpss=a*app.meters_per_feet;        
            end    
            if(app.n_accel==4) % m/sec^2
                app.a_mpss=a;
                app.a_G=a/9.81;
                app.a_ipss=a*app.inches_per_meters;
                app.a_ftpss=a*app.feet_per_meters;        
            end   

        end    

        function calculate_height(app)

            a=app.bH;

            if(app.n_height==1) % inch  
                app.h_in=a;
                app.h_ft=a/12;
                app.h_m=a*app.meters_per_inch;
            end           
            if(app.n_height==2) % ft
                app.h_ft=a;
                app.h_in=12*a;
                app.h_m=a*app.meters_per_feet;
            end           
            if(app.n_height==3) % meters
                app.h_m=a;
                app.h_ft=a*app.feet_per_meters;
                app.h_in=a*app.inches_per_meters;      
            end     

        end

        function calculate_fn(app)

            if(app.n_freq==1) % Hz
                app.fn=app.bfn;
                app.omegan=app.fn*app.tpi;
            else          % rad/sec
                app.omegan=app.bfn;
                app.fn=app.omegan/app.tpi;
            end    
        end
        
        function post(app)

            app.a_G=app.a_mpss/9.81;
            app.a_ipss=app.a_mpss*app.inches_per_meters;
            app.a_ftpss=app.a_mpss*app.feet_per_meters;  
            
            app.sd_m=sqrt(2*app.G*app.h_m)/app.omegan;
            app.sd_in=app.sd_m*app.inches_per_meters;
            app.sd_ft=app.sd_m*app.feet_per_meters;
            app.sd_mm=app.sd_m*1000;
            
            app.v_mps=app.sd_m*app.omegan;
            app.v_ips=app.sd_in*app.omegan;
            app.v_fps=app.sd_ft*app.omegan;            

            app.TabGroup.SelectedTab=app.ResultsTab;


            s1=sprintf(' %8.4g in \n %8.4g ft \n %8.4g m',app.h_in,app.h_ft,app.h_m);
            s2=sprintf(' %8.4g Hz \n %8.4g rad/sec',app.fn,app.omegan);
            s3=sprintf(' %8.4g in \n %8.4g ft \n %8.4g mm',app.sd_in,app.sd_ft,app.sd_mm);
            s4=sprintf(' %8.4g in/sec \n %8.4g ft/sec \n %8.4g m/sec',app.v_ips,app.v_fps,app.v_mps);
            s5=sprintf(' %8.4g G \n\n %8.4g in/sec^2 \n %8.4g ft/sec^2 \n %8.4g m/sec^2',...
                                                            app.a_G,app.a_ipss,app.a_ftpss,app.a_mpss);


            ss=sprintf('Displacement \n\n %s \n\n Velocity \n\n %s \n\n Acceleration \n\n %s \n\n',s3,s4,s5);
    
            app.DropHeightTextArea.Value=s1;
            app.NaturalFrequencyTextArea.Value=s2;
            app.PeakResponseValuesTextArea.Value=ss;

            app.TabGroup.SelectedTab=app.ResultsTab;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.meters_per_inch=0.0254;
            app.inches_per_meters=1/app.meters_per_inch;
            
            app.meters_per_feet=0.3048;
            app.feet_per_meters=1/app.meters_per_feet;

            app.tpi=2*pi;

            app.G=9.81;

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_acceleration
        function CalculateButton_accelerationPushed(app, event)
            

            Items = strtrim(app.HeightAccelListBox.Items);
            im = strtrim(app.HeightAccelListBox.Value);
            app.n_height = find(strcmp(Items, im));

            app.bH=str2num(app.DropHeightAccelEditField.Value);

            calculate_height(app);

            Items = strtrim(app.fnAccelListBox.Items);
            im = strtrim(app.fnAccelListBox.Value);
            app.n_freq = find(strcmp(Items, im));
                    
            app.bfn=str2num(app.NaturalFrequencyAccelEditField.Value);
            calculate_fn(app);


            % calculate acceleration
                    
            app.a_mpss=app.omegan*sqrt(2*app.G*app.h_m);

            post(app);

        end

        % Button pushed function: CalculateButton_drop_height
        function CalculateButton_drop_heightPushed(app, event)
          
            Items = strtrim(app.fnHeightListBox.Items);
            im = strtrim(app.fnHeightListBox.Value);
            app.n_freq = find(strcmp(Items, im));

            app.bfn=str2num(app.NaturalFrequencyHeightEditField.Value);

            calculate_fn(app);

            Items = strtrim(app.AccelHeightListBox.Items);
            im = strtrim(app.AccelHeightListBox.Value);
            app.n_accel = find(strcmp(Items, im));

            app.bA=str2num(app.PeakAccelerationHeightEditField.Value);
          
            calculate_accel(app);

            app.h_m=(app.a_mpss^2/app.omegan^2)/(2*app.G);
            app.h_ft=app.h_m*app.feet_per_meters;
            app.h_in=app.h_m*app.inches_per_meters;

            post(app);

        end

        % Button pushed function: CalculateButton_fn
        function CalculateButton_fnPushed(app, event)
         
            Items = strtrim(app.AccelfnListBox.Items);
            im = strtrim(app.AccelfnListBox.Value);
            app.n_accel = find(strcmp(Items, im));

            app.bA=str2num(app.PeakAccelerationfnEditField.Value);
          
            calculate_accel(app);

            Items = strtrim(app.HeightfnListBox.Items);
            im = strtrim(app.HeightfnListBox.Value);
            app.n_height = find(strcmp(Items, im));

            app.bH=str2num(app.HeightfnEditField.Value);

            calculate_height(app);

            app.omegan=app.a_mpss/sqrt(2*app.G*app.h_m);
            app.fn=app.omegan/app.tpi;  

            post(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.accidentaldropshockUIFigure = uifigure('Visible', 'off');
            app.accidentaldropshockUIFigure.Name = 'accidental drop shock';
            app.accidental_drop_shock_aver11byTomIrvineLabel = uilabel(app.accidentaldropshockUIFigure);
            app.accidental_drop_shock_aver11byTomIrvineLabel.Text = 'accidental_drop_shock_a      ver 1.1    by Tom Irvine';
            app.ReturnButton = uibutton(app.accidentaldropshockUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.CalculateButton = uibutton(app.accidentaldropshockUIFigure, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.accidentaldropshockUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'accidental_drop.jpg');
            app.TabGroup = uitabgroup(app.accidentaldropshockUIFigure);
            app.AssumptionsTab = uitab(app.TabGroup);
            app.AssumptionsTab.Title = 'Assumptions';
            app.Label = uilabel(app.AssumptionsTab);
            app.Label.Text = {'Assumptions:  '; ''; '1. Single-degree-of-freedom system (spring-mass system) dropped from rest'; '2. Perfectly elastic impact'; '3. Linear response '; '4. Mass vibrates at its natural frequency after the impact'; ''};
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.CalculateButton_acceleration = uibutton(app.AccelerationTab, 'push');
            app.CalculateButton_acceleration.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_accelerationPushed, true);
            app.CalculateButton_acceleration.Text = 'Calculate';
            app.DropHeightEditFieldLabel = uilabel(app.AccelerationTab);
            app.DropHeightEditFieldLabel.Text = 'Drop Height';
            app.DropHeightAccelEditField = uieditfield(app.AccelerationTab, 'text');
            app.HeightAccelListBox = uilistbox(app.AccelerationTab);
            app.HeightAccelListBox.Items = {'in', 'ft', 'm'};
            app.HeightAccelListBox.Value = 'in';
            app.NaturalFrequencyEditFieldLabel = uilabel(app.AccelerationTab);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyAccelEditField = uieditfield(app.AccelerationTab, 'text');
            app.fnAccelListBox = uilistbox(app.AccelerationTab);
            app.fnAccelListBox.Items = {'Hz', 'rad/sec'};
            app.fnAccelListBox.Value = 'Hz';
            app.DropHeightTab = uitab(app.TabGroup);
            app.DropHeightTab.Title = 'Drop Height';
            app.CalculateButton_drop_height = uibutton(app.DropHeightTab, 'push');
            app.CalculateButton_drop_height.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_drop_heightPushed, true);
            app.CalculateButton_drop_height.Text = 'Calculate';
            app.fnHeightListBox = uilistbox(app.DropHeightTab);
            app.fnHeightListBox.Items = {'Hz', 'rad/sec'};
            app.fnHeightListBox.Value = 'Hz';
            app.NaturalFrequencyEditFieldLabel_2 = uilabel(app.DropHeightTab);
            app.NaturalFrequencyEditFieldLabel_2.Text = 'Natural Frequency';
            app.NaturalFrequencyHeightEditField = uieditfield(app.DropHeightTab, 'text');
            app.AccelHeightListBox = uilistbox(app.DropHeightTab);
            app.AccelHeightListBox.Items = {'G', 'in/sec^2', 'ft/sec^2', 'm/sec^2'};
            app.AccelHeightListBox.Value = 'G';
            app.PeakAccelerationEditFieldLabel = uilabel(app.DropHeightTab);
            app.PeakAccelerationEditFieldLabel.Text = 'Peak Acceleration';
            app.PeakAccelerationHeightEditField = uieditfield(app.DropHeightTab, 'text');
            app.NaturalFrequencyTab = uitab(app.TabGroup);
            app.NaturalFrequencyTab.Title = 'Natural Frequency';
            app.CalculateButton_fn = uibutton(app.NaturalFrequencyTab, 'push');
            app.CalculateButton_fn.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_fnPushed, true);
            app.CalculateButton_fn.Text = 'Calculate';
            app.HeightfnListBox = uilistbox(app.NaturalFrequencyTab);
            app.HeightfnListBox.Items = {'in', 'ft', 'm'};
            app.HeightfnListBox.Value = 'in';
            app.DropHeightEditFieldLabel_2 = uilabel(app.NaturalFrequencyTab);
            app.DropHeightEditFieldLabel_2.Text = 'Drop Height';
            app.HeightfnEditField = uieditfield(app.NaturalFrequencyTab, 'text');
            app.AccelfnListBox = uilistbox(app.NaturalFrequencyTab);
            app.AccelfnListBox.Items = {'G', 'in/sec^2', 'ft/sec^2', 'm/sec^2'};
            app.AccelfnListBox.Value = 'G';
            app.PeakAccelerationEditFieldLabel_2 = uilabel(app.NaturalFrequencyTab);
            app.PeakAccelerationEditFieldLabel_2.Text = 'Peak Acceleration';
            app.PeakAccelerationfnEditField = uieditfield(app.NaturalFrequencyTab, 'text');
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.DropHeightTextAreaLabel = uilabel(app.ResultsTab);
            app.DropHeightTextAreaLabel.Text = 'Drop Height';
            app.DropHeightTextArea = uitextarea(app.ResultsTab);
            app.NaturalFrequencyTextAreaLabel = uilabel(app.ResultsTab);
            app.NaturalFrequencyTextAreaLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyTextArea = uitextarea(app.ResultsTab);
            app.PeakResponseValuesTextAreaLabel = uilabel(app.ResultsTab);
            app.PeakResponseValuesTextAreaLabel.Text = 'Peak Response Values';
            app.PeakResponseValuesTextArea = uitextarea(app.ResultsTab);
            % Show the figure after all components are created
            app.accidentaldropshockUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accidental_drop_shock_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.accidentaldropshockUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.accidentaldropshockUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.accidentaldropshockUIFigure)
        end
    end
end