classdef acoustic_seismic_coupling_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcousticSeismicCouplingUIFigure  matlab.ui.Figure
        Label                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalculationTab                  matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        PSDFrequenciesHzLabel           matlab.ui.control.Label
        CoefficientinchpsisecEditField  matlab.ui.control.EditField
        CoefficientinchpsisecLabel      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SaveTab                         matlab.ui.container.Tab
        VelocityPSDEditField            matlab.ui.control.EditField
        VelocityPSDEditFieldLabel       matlab.ui.control.Label
        AccelerationPSDEditField        matlab.ui.control.EditField
        AccelerationPSDEditFieldLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ReferenceTab                    matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        acoustic_seismic_coupling_aver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        FS % Description
        THM  
        accel_psd
        velox_psd
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end

       function update(app)
            ClosePlots(app);
            try
                cell_array=update_spl();
            catch
                msgbox('updata_spl found no files');
            end
            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
                app.listbox_method.Value=app.listbox_method.Items{1}; 
            catch
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    app.FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',app.FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end

            K=str2num(app.CoefficientinchpsisecEditField.Value);
    
            ipress=1;
            
            dB=app.THM(:,2);
            %
            [oadb]=oaspl_function(dB);
            %
            fprintf('\n  Overall Level = %8.4g dB \n\n',oadb);
            disp('  ');
            disp(' zero dB Reference = 20 micro Pascal ');
            disp('  ');
            %
            figure(fig_num);
            fig_num=fig_num+1;
            %
            plot(app.THM(:,1),app.THM(:,2));
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','lin')
            grid on;
            out1=sprintf(' One-Third Octave Sound Pressure Level  OASPL = %8.4g dB ',oadb);
            title(out1)
            xlabel(' Center Frequency (Hz) ');
            ylabel(' SPL (dB) ');
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(ipress==1)
                reference = 2.9e-09;
            end
            if(ipress==2)
                reference = 20.e-06;
            end
            if(ipress==3) 
                reference = 20.;
            end
            %
            fc=app.THM(:,1);
            spl=app.THM(:,2);
            %
            rms=0.;
            %   
            num=length(fc);
            psd=zeros(num,1);
            %
            %
            delta=(2^(1/6)) - 1/(2^(1/6));
            %
            for i=1:num    
            %	
                if( spl(i) >= 1.0e-50)
            %		
                    pressure_rms=reference*(10.^(spl(i)/20.) );
            %
		            df=fc(i)*delta;
            %
                    if( df > 0. )	
                        psd(i)=(pressure_rms^2.)/df;
			            rms=rms+psd(i)*df;
                    end
                end
            end
            %
            %
            rms=sqrt(rms);
            
            if(ipress==1)
                disp(' Dimensions are: freq(Hz) & PSD(psi^2/Hz) ');
                ylab='Pressure (psi^2/Hz)';
                tstring=sprintf('Pressure Power Spectral Density  %8.4g psi rms overall',rms);
            end
            if(ipress==2)
                disp(' Dimensions are: freq(Hz) & PSD(Pascal^2/Hz) ');
                ylab='Pressure (Pascal^2/Hz)'; 
                tstring=sprintf('Pressure Power Spectral Density  %8.4g Pa rms overall',rms);    
            end
            if(ipress==3)
                disp(' Dimensions are: freq(Hz) & PSD(micro Pascal^2/Hz) ');
                ylab='Pressure (micro Pascal^2/Hz)';     
                tstring=sprintf('Pressure Power Spectral Density  %8.4g micro Pa rms overall',rms);    
            end
            
            %
            %     
            figure(fig_num);
            fig_num=fig_num+1;
            plot(fc,psd);
            xlabel('Frequency (Hz)');
            ylabel(ylab);
            title(tstring);
            grid on;
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log')
            
            %%%%%%%
            
            app.velox_psd=K^2*psd;
            app.accel_psd=zeros(num,1);
            
            for i=1:num
                omega2=(2*pi*fc(i))^2;
                app.accel_psd(i)=app.velox_psd(i)*omega2/386^2;
            end
            
            fmin=str2num(app.MinEditField.Value);
            fmax=str2num(app.MaxEditField.Value);
            
            [~,i]=min(abs(fc-fmin));
            [~,j]=min(abs(fc-fmax));        
            
            fc=fc(i:j);
            app.velox_psd=app.velox_psd(i:j);
            app.accel_psd=app.accel_psd(i:j);
            
            [~,vrms] = calculate_PSD_slopes(fc,app.velox_psd);
            [~,grms] = calculate_PSD_slopes(fc,app.accel_psd);
            
            
            app.velox_psd=[fc app.velox_psd];
            app.accel_psd=[fc app.accel_psd];
            
            %%%%%%%
            
            md=6;
            x_label='Frequency (Hz)';
            fmin=fc(1);
            fmax=fc(end);
            
            t_string=sprintf('Velocity PSD   %7.3g in/sec RMS overall',vrms);
            y_label='Vel ((in/sec)^2/Hz)';
            ppp=app.velox_psd;
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            t_string=sprintf('Acceleration PSD   %7.3g GRMS overall',grms);
            y_label='Accel (G^2/Hz)';
            ppp=app.accel_psd;
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%%%%%%


        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               app.THM

               try
                    update(app);             
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    

        end

        % Value changed function: CoefficientinchpsisecEditField
        function CoefficientinchpsisecEditFieldValueChanged(app, event)
            ClosePlots(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
      
            output_name=app.AccelerationPSDEditField.Value;
            assignin('base', output_name, app.accel_psd);

            output_name=app.VelocityPSDEditField.Value;            
            assignin('base', output_name, app.velox_psd);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcousticSeismicCouplingUIFigure = uifigure('Visible', 'off');
            app.AcousticSeismicCouplingUIFigure.Name = 'Acoustic Seismic Coupling';
            app.acoustic_seismic_coupling_aver12byTomIrvineLabel = uilabel(app.AcousticSeismicCouplingUIFigure);
            app.acoustic_seismic_coupling_aver12byTomIrvineLabel.Text = 'acoustic_seismic_coupling_a   ver 1.2   by Tom Irvine';
            app.TabGroup = uitabgroup(app.AcousticSeismicCouplingUIFigure);
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.CalculateButton = uibutton(app.CalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CoefficientinchpsisecLabel = uilabel(app.CalculationTab);
            app.CoefficientinchpsisecLabel.Text = {'Coefficient  '; '[ inch / (psi sec) ]'};
            app.CoefficientinchpsisecEditField = uieditfield(app.CalculationTab, 'text');
            app.CoefficientinchpsisecEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientinchpsisecEditFieldValueChanged, true);
            app.CoefficientinchpsisecEditField.Value = '2';
            app.PSDFrequenciesHzLabel = uilabel(app.CalculationTab);
            app.PSDFrequenciesHzLabel.Text = 'PSD Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.CalculationTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculationTab, 'text');
            app.MinEditField.Value = '20';
            app.MaxEditFieldLabel = uilabel(app.CalculationTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculationTab, 'text');
            app.MaxEditField.Value = '2000';
            app.listbox_method = uilistbox(app.CalculationTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculationTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.CalculationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculationTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculationTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AccelerationPSDEditFieldLabel = uilabel(app.SaveTab);
            app.AccelerationPSDEditFieldLabel.Text = 'Acceleration PSD';
            app.AccelerationPSDEditField = uieditfield(app.SaveTab, 'text');
            app.AccelerationPSDEditField.Value = 'ground_accel_psd';
            app.VelocityPSDEditFieldLabel = uilabel(app.SaveTab);
            app.VelocityPSDEditFieldLabel.Text = 'Velocity PSD';
            app.VelocityPSDEditField = uieditfield(app.SaveTab, 'text');
            app.VelocityPSDEditField.Value = 'ground_vel_psd';
            app.ReferenceTab = uitab(app.TabGroup);
            app.ReferenceTab.Title = 'Reference';
            app.Label_2 = uilabel(app.ReferenceTab);
            app.Label_2.Text = {'Sutherland, L. C., “Sonic and Vibration Environments for Ground Facilities - A Design Manual,” '; 'NASA CR-61636,  Mar. 1968 (NASA Acc. N76-71920). '};
            app.ReturnButton = uibutton(app.AcousticSeismicCouplingUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.AcousticSeismicCouplingUIFigure);
            app.Label.Text = {'This script calculates the seismic PSD response to a one-third octave band SPL to a pressure PSD.   '; ''; 'The input SPL must have two columns:  freq(Hz) & SPL(dB)'};
            % Show the figure after all components are created
            app.AcousticSeismicCouplingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acoustic_seismic_coupling_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AcousticSeismicCouplingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AcousticSeismicCouplingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcousticSeismicCouplingUIFigure)
        end
    end
end