classdef aerodynamic_flow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        aerodynamicflowUIFigure        matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        LoadModelButton                matlab.ui.control.Button
        SaveModelButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        InputTrajectoryTab             matlab.ui.container.Tab
        TheinputarraymusthavetwocolumnsaltitudevelocityLabel  matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        VelocityUnitListBox            matlab.ui.control.ListBox
        VelocityUnitListBoxLabel       matlab.ui.control.Label
        AltitudeUnitListBox            matlab.ui.control.ListBox
        AltitudeUnitListBoxLabel       matlab.ui.control.Label
        CalculateTab                   matlab.ui.container.Tab
        SeparatedFlowCompressionCornerListBox  matlab.ui.control.ListBox
        SeparatedFlowCompressionCornerListBoxLabel  matlab.ui.control.Label
        CaculateButton                 matlab.ui.control.Button
        FlowRegimeListBox              matlab.ui.control.ListBox
        FlowRegimeListBoxLabel         matlab.ui.control.Label
        UpstreamMachNumber1EditField   matlab.ui.control.EditField
        UpstreamMachNumber1EditFieldLabel  matlab.ui.control.Label
        FrustumAngledegEditField       matlab.ui.control.EditField
        FrustumAngledegEditFieldLabel  matlab.ui.control.Label
        QScaleEditField                matlab.ui.control.EditField
        QScaleEditFieldLabel           matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        StationxrelativetofrontendEditField  matlab.ui.control.EditField
        StationxrelativetofrontendEditFieldLabel  matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        OutputFunctionListBox          matlab.ui.control.ListBox
        OutputFunctionListBoxLabel     matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FrankenMethodButton            matlab.ui.control.Button
        aerodynamic_flowver15byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        freq % Description
        x

        fplref
        ft_per_km
        conv
        max_fpl
        iflow
        icc
        iex
        altitude
        alt_num
        h
        M
        U
        maxG
        aeroflow_fpl

        c
        q
        rho
        oafpl
        maxfplcase
        speed_of_sound

        THM

        M2
        F

    end
    
    methods (Access = private)

        function mach(app)
	        WT_ratio=1.;
	        app.M2 = app.M^2;
            app.F= 0.5 + WT_ratio*(0.5 + 0.09*app.M2) + 0.04*app.M2;
        end

        function reverse_speed(app)
            
            app.alt_num=length(app.altitude);
            
            app.c=zeros(app.alt_num,1);
            
            
            for ikk=1:app.alt_num
               
                app.h=app.altitude(ikk);
                
               
                LH1=11*app.ft_per_km;
                LH2=20*app.ft_per_km;
                LH3=40*app.ft_per_km;
              
                if( app.h < LH1)  % troposphere
	            
                    Tz=288.;
		            L=6.5;
                    gamma=1.402;
                    ROM = (8314.3/28.97);
            
                    if( app.h < 0.)
			            app.h=0;
                    end
            
		            app.c(ikk)=sqrt(abs(gamma*ROM*(Tz-L*(app.h/(app.ft_per_km)))))*3.28;
                    
                 end
                 if( app.h >= LH1 && app.h <= LH2 )  % lower stratosphere
                        
                    app.c(ikk) = 295.*3.28;
                                    
                 end
                 if( app.h> LH2 && app.h <= LH3)
                    
                     clear y;
                     y(1)=20;
                     y(2)=25;
                     y(3)=30;
                     y(4)=35;
                     y(5)=40;
                     y=y*app.ft_per_km;
            
                     cc(1)=295;
                     cc(2)=298;
                     cc(3)=302;
                     cc(4)=308;
                     cc(5)=317;
                     cc=cc*3.28;
            
                     for i=1:(length(y)-1)
                        if(app.h>=y(i) && app.h<=y(i+1))
                            x1=y(i);
                            y1=cc(i);
                            x2=y(i+1);
                            y2=cc(i+1);
                            xnew=app.h;
                            [app.c(ikk)]=linear_interpolation_function(x1,y1,x2,y2,xnew);
                            break;
                        end    
                     end    
            
                 end    
                 if( app.h > LH3 )
                        warndlg('Altitude too high for speed of sound calculation');
                        return;
                 end
            end
    
        end

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     
        end

        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};         
        end

        function one_third_octave_freq(app)
            app.freq(1)=20.;
	        app.freq(2)=25.;
	        app.freq(3)=31.5;
	        app.freq(4)=40.;
	        app.freq(5)=50.;
	        app.freq(6)=63.;
	        app.freq(7)=80.;
	        app.freq(8)=100.;
	        app.freq(9)=125.;
	        app.freq(10)=160.;
	        app.freq(11)=200.;
	        app.freq(12)=250.;
	        app.freq(13)=315.;
	        app.freq(14)=400.;
	        app.freq(15)=500.;
	        app.freq(16)=630.;
	        app.freq(17)=800.;
	        app.freq(18)=1000.;
	        app.freq(19)=1250.;
	        app.freq(20)=1600.;
	        app.freq(21)=2000.;
	        app.freq(22)=2500.;
	        app.freq(23)=3150.;
	        app.freq(24)=4000.;
	        app.freq(25)=5000.;
	        app.freq(26)=6300.;
	        app.freq(27)=8000.;
	        app.freq(28)=10000.;
        end   

        function listbox_aux(app)
            
            app.SaveButton.Enable='off';

            Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
            im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
            n = find(strcmp(Items, im));
            
            app.UpstreamMachNumber1EditField.Visible='off';
            app.UpstreamMachNumber1EditFieldLabel.Visible='off';

            app.FrustumAngledegEditField.Visible='off';
            app.FrustumAngledegEditFieldLabel.Visible='off';
            
            if(n>=3)
                app.UpstreamMachNumber1EditField.Visible='on';
                app.UpstreamMachNumber1EditFieldLabel.Visible='on';
    
                app.FrustumAngledegEditField.Visible='on';
                app.FrustumAngledegEditFieldLabel.Visible='on';
            end

        end    

        function FlowRegimeChange(app)

            app.SaveButton.Enable='off';

            m=app.SeparatedFlowCompressionCornerListBox.Items{1};
            app.SeparatedFlowCompressionCornerListBox.Value=m;
            
            app.UpstreamMachNumber1EditField.Visible='off';
            app.UpstreamMachNumber1EditFieldLabel.Visible='off';
            app.FrustumAngledegEditField.Visible='off';
            app.FrustumAngledegEditFieldLabel.Visible='off';
            
            Items = strtrim(app.FlowRegimeListBox.Items);
            im = strtrim(app.FlowRegimeListBox.Value);
            app.iflow = find(strcmp(Items, im));

            if(app.iflow==1)
                app.SeparatedFlowCompressionCornerListBox.Visible='off';
                app.SeparatedFlowCompressionCornerListBoxLabel.Visible='off';
            else
                app.SeparatedFlowCompressionCornerListBox.Visible='on';
                app.SeparatedFlowCompressionCornerListBoxLabel.Visible='on';
                
                listbox_aux(app);   
            end
                       
            if(app.iflow==2)
                app.SeparatedFlowCompressionCornerListBoxLabel.Text='Separated Flow, Compression Corner';
                string_th{1}='Plateau Region, Transonic Flow (IV)';                             
                string_th{2}='Reattachment Region, Transonic Flow (V)';                         
                string_th{3}='Plateau Region, Supersonic Flow (IV)';                           
                string_th{4}='Separation or Reattachment Shockwave (Local Supersonic Flow) (V)';
                app.SeparatedFlowCompressionCornerListBox.Items=string_th;    
                
            end
            if(app.iflow==3)
                app.SeparatedFlowCompressionCornerListBoxLabel.Text='Separated Flow, Expansion Corner';
                string_th{1}='Plateau Region, Transonic and Supersonic (II)';                             
                string_th{2}='Reattachment Region, Transonic (III)';                         
                app.SeparatedFlowCompressionCornerListBox.Items=string_th;    
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            one_third_octave_freq(app);
            update(app);
            FlowRegimeChange(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: FrankenMethodButton
        function FrankenMethodButtonPushed(app, event)
            Franken_method;
        end

        % Button pushed function: CaculateButton
        function CaculateButtonPushed(app, event)
            ClosePlots(app);

            disp('  ');
            disp(' * * * * * * * * * ');
            disp('  ');
            
            fig_num=1;

            %%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.fplref = (2.9e-09)*144.;
            
            ft_per_m   = 3.28;
            app.ft_per_km  = 3.28*1000.;
            ft_per_nmi = 6076.1;
            
            % km_per_nmi = 1.852;
            
            app.conv = 0.00194;  % kg/m^3 to slugs/ft^3  (lbf sec^2/ft^4)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.max_fpl=zeros(28,1);

            %%%

            Items = strtrim(app.FlowRegimeListBox.Items);
            im = strtrim(app.FlowRegimeListBox.Value);
            app.iflow = find(strcmp(Items, im));
            
            app.icc=0;
            if(app.iflow==2)
                Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
                im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
                app.icc = find(strcmp(Items, im));
            end
  
            %%%
            
            app.iex=0;
            if(app.iflow==3)
                Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
                im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
                app.iexp = find(strcmp(Items, im));
            end
            
            %%%
            
            
            app.x=str2num(app.StationxrelativetofrontendEditField.Value);

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            iu = find(strcmp(Items, im));

            if(iu==1)       % convert inch to ft
                app.x=app.x/12;
            else            % convert m to ft
                app.x=app.x*3.2808;     
            end
            
            fprintf('\n  x=%8.4g ft \n\n',app.x);
            
            %%%%%%%%
            
            app.altitude=app.THM(:,1);
            velox=app.THM(:,2);
            
            app.alt_num=length(app.altitude);
            
            %%%%%%%%

            Items = strtrim(app.VelocityUnitListBox.Items);
            im = strtrim(app.VelocityUnitListBox.Value);
            ivel_unit = find(strcmp(Items, im));
            
            Items = strtrim(app.AltitudeUnitListBox.Items);
            im = strtrim(app.AltitudeUnitListBox.Value);
            ialt_unit = find(strcmp(Items, im));
            
            if(ialt_unit==1)
                app.altitude=app.altitude*ft_per_nmi;
            end
            if(ialt_unit==3)
                app.altitude=app.altitude*app.ft_per_km;
            end
            
            max_alt=131200;
            
            for i=app.alt_num:1:-1
                if(app.altitude(i)>max_alt)
                    app.altitude(i)=[];
                    velox(i)=[];
                end
            end
            
            app.alt_num=length(app.altitude);
            
            %%%%%%%%%

            reverse_speed(app);
            
            if(ivel_unit==1)  % convert Mach to ft/sec
                
                macht=velox;
                
                for i=1:app.alt_num
                    velox(i)=velox(i)*app.speed_of_sound(i);  
                end
                  
            end     
            if(ivel_unit==3)  % convert m/sec to ft/sec
                velox=velox*ft_per_m;
            end
            if(ivel_unit>=2)
                
                for i=1:app.alt_num    
                   macht(i)=velox(i)/app.speed_of_sound(i);       
                end   
                
            end
            
            %%%%%%%%
            
            for i=app.alt_num:1:-1
                if(macht(i)<1)
                    app.altitude(i)=[];
                    velox(i)=[];
                    macht(i)=[];
                end
            end
            
            app.alt_num=length(app.altitude);
            
            maxoafpl=0; 
            
            %%%%%%%%
            
            QS=str2num(app.QScaleEditField.Value);
            
            for i=1:app.alt_num
                
                app.h=app.altitude(i);
	            app.M=macht(i);  
                    
                if(app.h>131200)
                    warndlg('Maximum allowable altitude is 131200 feet');
                    return;
                end

	            mach(app);
            
	            app.U=velox(i);

	            density(hObject, eventdata, handles);        
                    
                app.q = 0.5*app.rho*(app.U^2);
                app.q=app.q*QS;
                
                reynolds(app);
                
                
            %%
            
                if(app.iflow==1)  % Attached Flow (Type I)                                    
		            
                    if(i==1)
                        disp(' Attached Flow via the Laganelli and Wolfe method, AIAA paper 89-1064 ');
                    end 
            
		            attach(hObject, eventdata, handles);
	                
                end
             
                if(app.iflow==2)  % Separated Flow & Shockwaves: Compression Corner (IV or V)   
                    if(i==1)
                        disp(' Separated Flow & Shockwaves: Compression Corner  ');
                    end
                    if(app.icc==1)
                        if(i==1)
                            disp(' Plateau Region, Transonic Flow (Type IV) ');
                        end
			            cc_plateau_tf(hObject, eventdata, handles);
                    end
                    if(app.icc==2)
                        if(i==1)		
                            disp(' Reattachment Region, Transonic Flow (Type V) ');  
                        end
			            cc_reattachment_tf(hObject, eventdata, handles);
                    end
                    if(app.icc==3)
                        if(i==1)	
                            disp(' Plateau Region, Supersonic Flow (Type IV) '); 
                        end	
			            cc_plateau_supersonic(hObject, eventdata, handles);
                    end	
                    if(app.icc==4)
                        if(i==1)	
                            disp(' Separation or Reattachment Shockwave (Type V) ');
                        end
			            cc_separation_or_shockwave(hObject, eventdata, handles);
                    end
                    
                    if(app.icc>=3 && i==1)
                        angle=str2num(get(handles.edit_angle,'String'));
                        fprintf(' Frustum angle = %g deg \n',angle);
                        um=str2num(get(handles.edit_mach,'String'));
                        fprintf(' Upstream Mach number = %g \n',um);          
                    end
                    
		            cc_auto(hObject, eventdata, handles);
                end
		            if( app.iflow==3)  % Separated Flow & Shockwaves: Expansion Corner (II or III) 
                    
                        if(i==1)
                            disp(' Separated Flow & Shockwaves: Expansion Corner ');
                        end
                        
			            if(app.iex==1)
                            if(i==1)
					            disp(' Plateau Region, Transonic and Supersonic (Type II) ');
                            end
				            exp_plateau(hObject, eventdata, handles);
			            end
			            if(app.iex==2)	
				            if(i==1)
					            disp(' Reattachment Region, Transonic (Type III) ');
				            end
				            exp_reattachment(hObject, eventdata, handles);
			            end
			            exp_auto(hObject, eventdata, handles);
		            end
		            auto_spectrum(hObject, eventdata, handles);
            
                     
                    if(app.oafpl > maxoafpl )

			            maxq = app.q;
			            maxMach=app.M;
			            maxaltitude=app.altitude(i);
			            maxikk = i;
			            maxoafpl=app.oafpl;
                    end
            
                    
                    if(i==1)		
                        disp('  ');
                        disp('  alt       Mach     q        c       OAFPL');
                        disp('  (ft)       No.    (psf)   (ft/sec)  (dB)');
                    end
                    
                    fprintf(' %7.0f  %6.2f  %8.1f  %8.1f  %6.1f \n',app.h,app.M,app.q,app.c(i),app.oafpl);

            end
            
            try
                disp(' ');
                disp('  Maximum FPL ');
                disp(' ');
                disp('  Freq(Hz)   FPL(dB) ');
            
                for i=1:length(app.freq)
                    fprintf(' %7.1f  %7.1f \n',app.freq(i),app.maxfplcase(i)); 
                end
            
            catch
               warndlg('Output error.  Check input parameters.');
               return;
            end
            
            % out1=sprintf('\n Overall FPL = %7.1f dB \n',maxoafpl);
            % disp(out1);
            
            f=app.freq;
            dB=app.maxfplcase;
            n_type=1;
            [fig_num]=fpl_plot(fig_num,n_type,f,dB);
            
            f=fix_size(f);
            dB=fix_size(dB);
            app.maxG=fix_size(app.maxG);
            
            app.maxG=app.maxG/12^4;
            
            data1=[f dB];
            app.aeroflow_fpl=data1;
           
            data2=[f app.maxG];
            app.maxG=data2;
          
            app.SaveButton.Enable='on';
            
            prms=10^(maxoafpl/20)*(2.9e-09);
            
            fprintf('\n Overall Pressure = %8.4g psi rms \n',prms);
              fprintf('                  = %8.4g Pa rms \n\n',prms*6891.2);

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            app.SaveButton.Enable='off';
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end

        % Value changed function: FlowRegimeListBox
        function FlowRegimeListBoxValueChanged(app, event)

            FlowRegimeChange(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            output_name=app.OutputArrayNameEditField.Value;

            Items = strtrim(app.OutputFunctionListBox.Items);
            im = strtrim(app.OutputFunctionListBox.Value);
            n = find(strcmp(Items, im));
            
            if(n==1)
                data=app.aeroflow_fpl;    
                fprintf('\n Output Array - freq(Hz) & FPL(dB) \n    %s \n\n',output_name); 
            else
                data=app.maxG;    
            end
            if(n==2)
                fprintf('\n Output Array - freq(Hz) & PSD(psi^2/Hz) \n    %s \n\n',output_name);      
            end
            if(n==3)
                data(:,2)=data(:,2)*6891.2^2;
                fprintf('\n Output Array - freq(Hz) & PSD(Pa^2/Hz) \n    %s \n\n',output_name);     
            end
                
            assignin('base', output_name, data);
            
            msgbox('Save Complete'); 

        end

        % Value changed function: SeparatedFlowCompressionCornerListBox
        function SeparatedFlowCompressionCornerListBoxValueChanged(app, event)

            app.SaveButton.Enable='off';
        end

        % Value changed function: AltitudeUnitListBox
        function AltitudeUnitListBoxValueChanged(app, event)
 
            app.SaveButton.Enable='off';
        end

        % Value changed function: VelocityUnitListBox
        function VelocityUnitListBoxValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
         
            app.SaveButton.Enable='off';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
 
            app.SaveButton.Enable='off';
        end

        % Value changed function: StationxrelativetofrontendEditField
        function StationxrelativetofrontendEditFieldValueChanged(app, event)
           
            app.SaveButton.Enable='off';
        end

        % Value changed function: QScaleEditField
        function QScaleEditFieldValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end

        % Value changed function: FrustumAngledegEditField
        function FrustumAngledegEditFieldValueChanged(app, event)
          
            app.SaveButton.Enable='off';
        end

        % Value changed function: UpstreamMachNumber1EditField
        function UpstreamMachNumber1EditFieldValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.aerodynamicflowUIFigure = uifigure('Visible', 'off');
            app.aerodynamicflowUIFigure.Name = 'aerodynamic flow';
            app.aerodynamic_flowver15byTomIrvineLabel = uilabel(app.aerodynamicflowUIFigure);
            app.aerodynamic_flowver15byTomIrvineLabel.Text = 'aerodynamic_flow   ver 1.5  by Tom Irvine     ';
            app.TabGroup = uitabgroup(app.aerodynamicflowUIFigure);
            app.InputTrajectoryTab = uitab(app.TabGroup);
            app.InputTrajectoryTab.Title = 'Input Trajectory';
            app.AltitudeUnitListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.AltitudeUnitListBoxLabel.Text = 'Altitude Unit';
            app.AltitudeUnitListBox = uilistbox(app.InputTrajectoryTab);
            app.AltitudeUnitListBox.Items = {'nmi ', 'feet', 'km  '};
            app.AltitudeUnitListBox.ValueChangedFcn = createCallbackFcn(app, @AltitudeUnitListBoxValueChanged, true);
            app.AltitudeUnitListBox.Value = 'nmi ';
            app.VelocityUnitListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.VelocityUnitListBoxLabel.Text = 'Velocity Unit';
            app.VelocityUnitListBox = uilistbox(app.InputTrajectoryTab);
            app.VelocityUnitListBox.Items = {'Mach  ', 'ft/sec', 'm/sec'};
            app.VelocityUnitListBox.ValueChangedFcn = createCallbackFcn(app, @VelocityUnitListBoxValueChanged, true);
            app.VelocityUnitListBox.Value = 'Mach  ';
            app.listbox_method = uilistbox(app.InputTrajectoryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputTrajectoryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputTrajectoryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputTrajectoryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.TheinputarraymusthavetwocolumnsaltitudevelocityLabel = uilabel(app.InputTrajectoryTab);
            app.TheinputarraymusthavetwocolumnsaltitudevelocityLabel.Text = 'The input array must have two columns:  altitude & velocity';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.StationxrelativetofrontendEditFieldLabel = uilabel(app.CalculateTab);
            app.StationxrelativetofrontendEditFieldLabel.Text = 'Station x relative to front end';
            app.StationxrelativetofrontendEditField = uieditfield(app.CalculateTab, 'text');
            app.StationxrelativetofrontendEditField.ValueChangedFcn = createCallbackFcn(app, @StationxrelativetofrontendEditFieldValueChanged, true);
            app.UnitsListBox = uilistbox(app.CalculateTab);
            app.UnitsListBox.Items = {'inch', 'meters'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'inch';
            app.QScaleEditFieldLabel = uilabel(app.CalculateTab);
            app.QScaleEditFieldLabel.Text = 'Q Scale';
            app.QScaleEditField = uieditfield(app.CalculateTab, 'text');
            app.QScaleEditField.ValueChangedFcn = createCallbackFcn(app, @QScaleEditFieldValueChanged, true);
            app.QScaleEditField.Value = '1';
            app.FrustumAngledegEditFieldLabel = uilabel(app.CalculateTab);
            app.FrustumAngledegEditFieldLabel.Text = 'Frustum Angle (deg)';
            app.FrustumAngledegEditField = uieditfield(app.CalculateTab, 'text');
            app.FrustumAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @FrustumAngledegEditFieldValueChanged, true);
            app.UpstreamMachNumber1EditFieldLabel = uilabel(app.CalculateTab);
            app.UpstreamMachNumber1EditFieldLabel.Text = 'Upstream Mach Number >=1';
            app.UpstreamMachNumber1EditField = uieditfield(app.CalculateTab, 'text');
            app.UpstreamMachNumber1EditField.ValueChangedFcn = createCallbackFcn(app, @UpstreamMachNumber1EditFieldValueChanged, true);
            app.FlowRegimeListBoxLabel = uilabel(app.CalculateTab);
            app.FlowRegimeListBoxLabel.Text = 'Flow Regime';
            app.FlowRegimeListBox = uilistbox(app.CalculateTab);
            app.FlowRegimeListBox.Items = {'Attached Flow (Type I)                                     ', 'Separated Flow & Shockwaves: Compression Corner (IV or V)  ', 'Separated Flow & Shockwaves: Expansion Corner   (II or III)'};
            app.FlowRegimeListBox.ValueChangedFcn = createCallbackFcn(app, @FlowRegimeListBoxValueChanged, true);
            app.FlowRegimeListBox.Value = 'Attached Flow (Type I)                                     ';
            app.CaculateButton = uibutton(app.CalculateTab, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.SeparatedFlowCompressionCornerListBoxLabel = uilabel(app.CalculateTab);
            app.SeparatedFlowCompressionCornerListBoxLabel.Text = 'Separated Flow, Compression Corner';
            app.SeparatedFlowCompressionCornerListBox = uilistbox(app.CalculateTab);
            app.SeparatedFlowCompressionCornerListBox.Items = {'Plateau Region, Transonic Flow (IV)                             ', 'Reattachment Region, Transonic Flow (V)                         ', 'Plateau Region, Supersonic Flow  (IV)                           ', 'Separation or Reattachment Shockwave (Local Supersonic Flow) (V)'};
            app.SeparatedFlowCompressionCornerListBox.ValueChangedFcn = createCallbackFcn(app, @SeparatedFlowCompressionCornerListBoxValueChanged, true);
            app.SeparatedFlowCompressionCornerListBox.Value = 'Plateau Region, Transonic Flow (IV)                             ';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.FrankenMethodButton = uibutton(app.ExportTab, 'push');
            app.FrankenMethodButton.ButtonPushedFcn = createCallbackFcn(app, @FrankenMethodButtonPushed, true);
            app.FrankenMethodButton.Text = 'Franken Method';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.OutputFunctionListBoxLabel = uilabel(app.ExportTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox = uilistbox(app.ExportTab);
            app.OutputFunctionListBox.Items = {'FPL (dB)         ', 'Pressure PSD (psi^2/Hz)', 'Pressure PSD (Pa^2/Hz)'};
            app.OutputFunctionListBox.Value = 'FPL (dB)         ';
            app.SaveModelButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.LoadModelButton.Text = 'Load Model';
            app.ReturnButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.aerodynamicflowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aerodynamic_flow

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.aerodynamicflowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.aerodynamicflowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.aerodynamicflowUIFigure)
        end
    end
end