classdef aerodynamic_flow_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        aerodynamicflowUIFigure        matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        LoadModelButton                matlab.ui.control.Button
        SaveModelButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        InputTrajectoryTab             matlab.ui.container.Tab
        TheinputarraymusthavetwocolumnsaltitudevelocityLabel  matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        VelocityUnitListBox            matlab.ui.control.ListBox
        VelocityUnitListBoxLabel       matlab.ui.control.Label
        AltitudeUnitListBox            matlab.ui.control.ListBox
        AltitudeUnitListBoxLabel       matlab.ui.control.Label
        CalculateTab                   matlab.ui.container.Tab
        SeparatedFlowCompressionCornerListBox  matlab.ui.control.ListBox
        SeparatedFlowCompressionCornerListBoxLabel  matlab.ui.control.Label
        CaculateButton                 matlab.ui.control.Button
        FlowRegimeListBox              matlab.ui.control.ListBox
        FlowRegimeListBoxLabel         matlab.ui.control.Label
        UpstreamMachNumber1EditField   matlab.ui.control.EditField
        UpstreamMachNumber1EditFieldLabel  matlab.ui.control.Label
        FrustumAngledegEditField       matlab.ui.control.EditField
        FrustumAngledegEditFieldLabel  matlab.ui.control.Label
        QScaleEditField                matlab.ui.control.EditField
        QScaleEditFieldLabel           matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        StationxrelativetofrontendEditField  matlab.ui.control.EditField
        StationxrelativetofrontendEditFieldLabel  matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        OutputFunctionListBox          matlab.ui.control.ListBox
        OutputFunctionListBoxLabel     matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FrankenMethodButton            matlab.ui.control.Button
        aerodynamic_flow_aver14byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        aeroflow_fpl
        altitude
        alt_num
        c
        cexp
        comp
        conv
        deltastar
        F
        fpl
        fplref
        freq
        ft_per_km
        G
        Goct
        h
        icc
        iex
        iexp
        iflow
        M
        M2
        maxfplcase
        maxG
        max_fpl
        oafpl
        pdata
        P2P1
        poqcomp
        poqexp
        prms
        q
        Rex
        rho
        speed_of_sound
        THM
        U
        x       
    end
    
    methods (Access = private)

        function protuberance(app)

            protuberance_data(app)

            delta=0.37*app.x/app.Rex^0.2;

            px=app.pdata(:,1);
            py=app.pdata(:,2);

            np=length(px);

            ilast=length(app.freq);
            
            app.G=zeros(ilast,1);
                
            for ijk=1:ilast

                xn=app.freq(ijk)*delta/app.U;

                if(xn<px(1))
                    yn=py(1);
                end                 
                if(xn>px(end))
                    yn=py(end);
                end    
    
                if(xn>px(1) && xn<px(end))    

                    for i=1:np-1
        
                        if(xn>=px(i) && xn<=px(i+1))
                            x1=px(i);
                            y1=py(i);
                            x2=px(i+1);
                            y2=py(i+1);
                            [yn,~]=log_interpolation_function(x1,y1,x2,y2,xn);
                            break;
                        end    
       
                    end

                end

                % fprintf(' %8.4g  %8.4g  delta=%8.4g   U=%8.4g  q=%8.4g x=%8.4g\n',xn,yn,delta,app.U,app.q,app.x);

                app.G(ijk)=yn*app.q^2*delta/app.U;

            end    

        end    

        function protuberance_data(app)
            % NASA-CR-123961, Figure 2
            % log-log
              app.pdata=[0.0018123     0.50088 
              0.0036731      0.4443 
              0.0053475     0.35501 
              0.0069206      0.2836 
              0.0090932     0.17779 
               0.011858     0.12582 
               0.014797    0.083079 
               0.019155    0.056792 
               0.023553    0.038153 
               0.028756    0.022315 
               0.035886    0.013989 
               0.048941   0.0067646 
               0.064814   0.0031597 
               0.084568   0.0016093 
                0.11279  0.00086335 
                0.15965   0.0003392 
                0.21134  0.00019843 
                0.27557  0.00014791 
                0.41952  8.6548e-05 
                0.57594  6.3413e-05 
                0.78487  4.6462e-05 
                 1.0236   3.068e-05 
                 1.3449   1.957e-05 
                 1.6779  1.4841e-05]; 
        end    

        function aux_flow(app)
            app.SaveButton.Enable='off';

            Items = strtrim(app.FlowRegimeListBox.Items);
            im = strtrim(app.FlowRegimeListBox.Value);
            app.iflow = find(strcmp(Items, im));

            Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
            im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
            app.iex = find(strcmp(Items, im));
            
            app.UpstreamMachNumber1EditField.Visible='off';
            app.UpstreamMachNumber1EditFieldLabel.Visible='off';
            app.FrustumAngledegEditField.Visible='off';
            app.FrustumAngledegEditFieldLabel.Visible='off';
            
            if(app.iex>=3 && (app.iflow==2 || app.iflow==3))
                app.UpstreamMachNumber1EditField.Visible='on';
                app.UpstreamMachNumber1EditFieldLabel.Visible='on';
                app.FrustumAngledegEditField.Visible='on';
                app.FrustumAngledegEditFieldLabel.Visible='on'; 
            end
        end    

            function P2P1_ratio(app)
            %
                alpha=str2num(app.FrustumAngledegEditField.Value);
                   M1=str2num(app.UpstreamMachNumber1EditField.Value);
            
	            alpha=alpha*pi/180;
            
                if( M1 < 1.0 )
                    warndlg('Mach must be >=1 ');
                    return;
                end    
            
	            theta = alpha + asin(1/M1);
            
	            s2 = (sin(theta))^2;
            
	            app.P2P1 = ( (2.8*(M1^2)*s2) - 0.4 )/2.4;

            end
            
            
            function auto_spectrum(app)
             
                 num=length(app.freq);
                   
                 app.fpl=zeros(num,1);
                app.Goct=zeros(num,1);
                
            
                for jk=1:num
                
                    app.Goct(jk)= app.G(jk)*(app.freq(jk)*0.2301);
                
                    app.prms=sqrt(app.Goct(jk));
                    
                    app.fpl(jk)=20*log10(app.prms/app.fplref);
                
                end
                
                [app.oafpl]=oaspl_function(app.fpl);
            
            end
            
            function exp_auto(app)
            %
                TPI=2*pi;
                
                constant = 4*(app.poqexp^2)*app.cexp*(app.F^1.433);
                constant=constant*(app.q^2)*(app.deltastar/app.U);
                
                ilast=length(app.freq);
	                
                app.G=zeros(ilast,1);

                for i=1:ilast        
                    omega= TPI*app.freq(i)*app.deltastar/app.U;
	                app.G(i) = constant/(1. + (app.F^2.867)*((app.cexp*omega)^2));
                end
            
            end
            
            function exp_reattachment(app)
            %
                
                app.poqexp  = 0.16/(1. + app.M2);
                app.prms=app.poqexp*app.q;
                app.cexp = 9;

            end
            
            function exp_plateau(app)
            %
                app.poqexp  = 0.040/(1. + app.M2);
                app.prms=app.poqexp*app.q;
                app.cexp = 3.;
            
            end
            
            function cc_auto(app)
            %
                TPI=2*pi;
            
                ilast=length(app.freq);
                
                constant = 4.*(app.poqcomp^2)*app.comp*(app.F^1.433);
                constant=constant*(app.q^2.)*(app.deltastar/app.U);
                
                app.G=zeros(ilast,1);
                
                for i=1:ilast
                 
	                omega= TPI*app.freq(i)*app.deltastar/app.U; 
                    app.G(i) = constant/(1. + (app.F^2.867)*((app.comp*omega)^2));
                    
                end        
                    
            end
            
            function cc_separation_or_shockwave(app)
            %
            
                P2P1_ratio(app);
                
	            % poqtbl = (0.006/app.F);	   % turbulent boundary layer
	            % ptbl = (0.006/app.F)*app.q;
            
                if( app.P2P1 < 0.593 )
	            
		            warndlg(' (p2/p1) must be > 0.593 ');
                    return;
                    
                end
            
	            pshock= ( -1.181 + 1.713*app.P2P1 + 0.468*( app.P2P1^2) )*(0.006/app.F)*app.q;
            
                if( pshock < 0 )
		            warndlg(' pshock must be >= 0 ');
		            return;
                end
            
	            % poq = ( -1.181 + 1.713*app.P2P1 + 0.468*( app.P2P1^2) )*(0.006/app.F);
            
                app.comp = 30.;
            
                app.poqcomp = ( -1.181 + 1.713*app.P2P1 + 0.468*( app.P2P1^2) )*(0.006/app.F);    
                
            end
            
            function cc_plateau_supersonic(app)
            %
                
                P2P1_ratio(app);

	            poqtbl = (0.006/app.F);	% turbulent boundary layer
	            
	            poq = poqtbl*app.P2P1;	% plateau
            
                app.prms = poq*app.q;
	            app.comp = 10.;
	            app.poqcomp = poqtbl*app.P2P1;
            
            end
            
            function cc_reattachment_tf(app)
            %
	            app.comp = 9.;
	            app.poqcomp = 0.10/(1+app.M2);
	            app.prms = app.poqcomp*app.q;
                  
            end    
            
            function cc_plateau_tf(app)
            %
	            app.comp = 3.;
            
	            app.poqcomp = 0.025/(1.+app.M2);
	            app.prms = app.poqcomp*app.q;

            end    
                   
            function attach(app)
            %
            %  G is the pressure autospectrum
            
                TPI=2*pi;
                
	            app.prms = (0.01/app.F)*app.q;
	            poq = app.prms/app.q;
            
	            constant = 4.*(poq^2)*(app.F^1.433);
	            constant =constant*(app.q^2)*(app.deltastar/app.U);
	            
                
                ilast=length(app.freq);
            
                app.G=zeros(ilast,1);
                
                for i=1:ilast
	            
		            omega= TPI*app.freq(i)*app.deltastar/app.U;
            
		            app.G(i) = constant/(1 + (app.F^2.867)*(omega^2));
                
                end
                
            end  


        function density(app)
        %
            dens(1)=1.226;
            dens(2)=1.112;
            dens(3)=1.007;
            dens(4)=0.9096;
            dens(5)=0.8195;
            dens(6)=0.7365;
            dens(7)=0.6600;
            dens(8)=0.5898;
            dens(9)=0.5254;
            dens(10)=0.4666;
            dens(11)=0.4129;
            dens(12)=0.3641;
            dens(13)=0.3104;
            dens(14)=0.2652;
            dens(15)=0.2266;
            dens(16)=0.1936;
            dens(17)=0.1654;
            dens(18)=0.1413;
            dens(19)=0.1207;
            dens(20)=0.1032;
            dens(21)=0.075715;
            dens(22)=0.064510;
            dens(23)=0.055006;
            dens(24)=0.046938;
            
            dens=dens*app.conv;    % kg/m^3 to slugs/ft^3  (lbf sec^2/ft^4)
            
            nn=length(dens);
            
            alt=zeros(nn,1);
            
            for i=1:nn
                alt(i)=(i-1)*app.ft_per_km;
            end
            
            app.rho=0.;
            
            for i=1:(nn-1)
                
                if( app.h >= alt(i) && app.h < alt(i+1) )
		            
                    len = alt(i+1)-alt(i);
                    c2  = (app.h-alt(i))/len;
                    c1  = 1. -c2;
            
		            app.rho = c1*dens(i) + c2*dens(i+1);
            
		            break;
                end	
            
            end

        end

        function reynolds(app)

            ht=app.h/1000.;
            
            if(app.h<360000.)
                mu = 1.560401 + 0.0395762*ht + 3.009485e-04*(ht^2)+ 1.675145e-05*(ht^3);
            else
                mu = -4.48529 + 0.4546508*ht - 1.090837e-02*(ht^2)+ 1.373416e-04*(ht^3);
            end
            mu=mu*0.0001;  % Kinematic viscosity
            
            app.Rex = app.U*app.x/mu;     % Equation (6.23) in heat transfer text
            
            app.deltastar = app.x*0.0371*(app.Rex^(-0.2))*((9/7)+0.475*app.M2)/((1.+0.13*app.M2)^0.64);

        end

        function mach(app)
	        WT_ratio=1.;
	        app.M2 = app.M^2;
            app.F= 0.5 + WT_ratio*(0.5 + 0.09*app.M2) + 0.04*app.M2;
        end

        function reverse_speed(app)
            
            app.alt_num=length(app.altitude);
            
            app.c=zeros(app.alt_num,1);
               
            for ikk=1:app.alt_num
               
                app.h=app.altitude(ikk);
                             
                LH1=11*app.ft_per_km;
                LH2=20*app.ft_per_km;
                LH3=40*app.ft_per_km;
              
                if( app.h < LH1)  % troposphere
	            
                    Tz=288.;
		            L=6.5;
                    gamma=1.402;
                    ROM = (8314.3/28.97);
            
                    if( app.h < 0.)
			            app.h=0;
                    end
            
		            app.c(ikk)=sqrt(abs(gamma*ROM*(Tz-L*(app.h/(app.ft_per_km)))))*3.28;
                    
                 end
                 if( app.h >= LH1 && app.h <= LH2 )  % lower stratosphere
                        
                    app.c(ikk) = 295.*3.28;
                                    
                 end
                 if( app.h> LH2 && app.h <= LH3)
                    
                     clear y;
                     y(1)=20;
                     y(2)=25;
                     y(3)=30;
                     y(4)=35;
                     y(5)=40;
                     y=y*app.ft_per_km;
            
                     cc(1)=295;
                     cc(2)=298;
                     cc(3)=302;
                     cc(4)=308;
                     cc(5)=317;
                     cc=cc*3.28;
            
                     for i=1:(length(y)-1)
                        if(app.h>=y(i) && app.h<=y(i+1))
                            x1=y(i);
                            y1=cc(i);
                            x2=y(i+1);
                            y2=cc(i+1);
                            xnew=app.h;
                            [app.c(ikk)]=linear_interpolation_function(x1,y1,x2,y2,xnew);
                            break;
                        end    
                     end    
            
                 end    
                 if( app.h > LH3 )
                        warndlg('Altitude too high for speed of sound calculation');
                        return;
                 end
            end

            app.speed_of_sound=app.c;
    
        end

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     
        end

        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};         
        end

        function one_third_octave_freq(app)
            app.freq(1)=20.;
	        app.freq(2)=25.;
	        app.freq(3)=31.5;
	        app.freq(4)=40.;
	        app.freq(5)=50.;
	        app.freq(6)=63.;
	        app.freq(7)=80.;
	        app.freq(8)=100.;
	        app.freq(9)=125.;
	        app.freq(10)=160.;
	        app.freq(11)=200.;
	        app.freq(12)=250.;
	        app.freq(13)=315.;
	        app.freq(14)=400.;
	        app.freq(15)=500.;
	        app.freq(16)=630.;
	        app.freq(17)=800.;
	        app.freq(18)=1000.;
	        app.freq(19)=1250.;
	        app.freq(20)=1600.;
	        app.freq(21)=2000.;
	        app.freq(22)=2500.;
	        app.freq(23)=3150.;
	        app.freq(24)=4000.;
	        app.freq(25)=5000.;
	        app.freq(26)=6300.;
	        app.freq(27)=8000.;
	        app.freq(28)=10000.;
        end   

        function FlowRegimeChange(app)

            app.SaveButton.Enable='off';

            m=app.SeparatedFlowCompressionCornerListBox.Items{1};
            app.SeparatedFlowCompressionCornerListBox.Value=m;
            
            app.UpstreamMachNumber1EditField.Visible='off';
            app.UpstreamMachNumber1EditFieldLabel.Visible='off';
            app.FrustumAngledegEditField.Visible='off';
            app.FrustumAngledegEditFieldLabel.Visible='off';
            
            Items = strtrim(app.FlowRegimeListBox.Items);
            im = strtrim(app.FlowRegimeListBox.Value);
            app.iflow = find(strcmp(Items, im));

            app.SeparatedFlowCompressionCornerListBox.Visible='off';
            app.SeparatedFlowCompressionCornerListBoxLabel.Visible='off';
          
            if(app.iflow==2 || app.iflow==3)
                app.SeparatedFlowCompressionCornerListBox.Visible='on';
                app.SeparatedFlowCompressionCornerListBoxLabel.Visible='on';
                
                aux_flow(app);   
            end
                       
            if(app.iflow==2)
                app.SeparatedFlowCompressionCornerListBoxLabel.Text='Separated Flow, Compression Corner';
                string_th{1}='Plateau Region, Transonic Flow (IV)';                             
                string_th{2}='Reattachment Region, Transonic Flow (V)';                         
                string_th{3}='Plateau Region, Supersonic Flow (IV)';                           
                string_th{4}='Separation or Reattachment Shockwave (Local Supersonic Flow) (V)';
                app.SeparatedFlowCompressionCornerListBox.Items=string_th;    
                
            end
            if(app.iflow==3)
                app.SeparatedFlowCompressionCornerListBoxLabel.Text='Separated Flow, Expansion Corner';
                string_th{1}='Plateau Region, Transonic and Supersonic (II)';                             
                string_th{2}='Reattachment Region, Transonic (III)';                         
                app.SeparatedFlowCompressionCornerListBox.Items=string_th;    
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            one_third_octave_freq(app);
            update(app);
            FlowRegimeChange(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: FrankenMethodButton
        function FrankenMethodButtonPushed(app, event)
            Franken_method;
        end

        % Button pushed function: CaculateButton
        function CaculateButtonPushed(app, event)
            ClosePlots(app);

            disp('  ');
            disp(' * * * * * * * * * ');
            disp('  ');
            
            fig_num=1;

            %%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.fplref = (2.9e-09)*144.;
            
            ft_per_m   = 3.28;
            app.ft_per_km  = 3.28*1000.;
            ft_per_nmi = 6076.1;
            
            % km_per_nmi = 1.852;
            
            app.conv = 0.00194;  % kg/m^3 to slugs/ft^3  (lbf sec^2/ft^4)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.max_fpl=zeros(28,1);

            %%%

            Items = strtrim(app.FlowRegimeListBox.Items);
            im = strtrim(app.FlowRegimeListBox.Value);
            app.iflow = find(strcmp(Items, im));
            
            app.icc=0;
            if(app.iflow==2)
                Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
                im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
                app.icc = find(strcmp(Items, im));
            end
  
            %%%
            
            app.iex=0;
            if(app.iflow==3)
                Items = strtrim(app.SeparatedFlowCompressionCornerListBox.Items);
                im = strtrim(app.SeparatedFlowCompressionCornerListBox.Value);
                app.iex = find(strcmp(Items, im));
            end
            
            %%%
            
            app.x=str2num(app.StationxrelativetofrontendEditField.Value);

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            iu = find(strcmp(Items, im));

            if(iu==1)       % convert inch to ft
                app.x=app.x/12;
            else            % convert m to ft
                app.x=app.x*3.2808;     
            end
            
            fprintf('\n  x=%8.4g ft \n\n',app.x);
            
            %%%%%%%%
            
            app.altitude=app.THM(:,1);
            velox=app.THM(:,2);
            
            app.alt_num=length(app.altitude);
            
            %%%%%%%%

            Items = strtrim(app.VelocityUnitListBox.Items);
            im = strtrim(app.VelocityUnitListBox.Value);
            ivel_unit = find(strcmp(Items, im));
            
            Items = strtrim(app.AltitudeUnitListBox.Items);
            im = strtrim(app.AltitudeUnitListBox.Value);
            ialt_unit = find(strcmp(Items, im));
            
            if(ialt_unit==1)
                app.altitude=app.altitude*ft_per_nmi;
            end
            if(ialt_unit==3)
                app.altitude=app.altitude*app.ft_per_km;
            end
            
            max_alt=131200;
            
            for i=app.alt_num:1:-1
                if(app.altitude(i)>max_alt)
                    app.altitude(i)=[];
                    velox(i)=[];
                end
            end
            
            app.alt_num=length(app.altitude);
            
            %%%%%%%%%

            reverse_speed(app);
            
            if(ivel_unit==1)  % convert Mach to ft/sec
                
                macht=velox;

                for i=1:app.alt_num
                    velox(i)=velox(i)*app.speed_of_sound(i);  
                end
                  
            end     
            if(ivel_unit==3)  % convert m/sec to ft/sec
                velox=velox*ft_per_m;
            end
            if(ivel_unit>=2)
                
                for i=1:app.alt_num    
                   macht(i)=velox(i)/app.speed_of_sound(i);       
                end   
                
            end
            
            %%%%%%%%
            
            for i=app.alt_num:1:-1
                if(macht(i)<1)
                    app.altitude(i)=[];
                    velox(i)=[];
                    macht(i)=[];
                end
            end
            
            app.alt_num=length(app.altitude);
           
            
            %%%%%%%%
            
            QS=str2num(app.QScaleEditField.Value);
            
            maxoafpl=0;
            app.maxG=0;
  
            for i=1:app.alt_num
                
                app.h=app.altitude(i);
	            app.M=macht(i);  
                    
                if(app.h>131200)
                    warndlg('Maximum allowable altitude is 131200 feet');
                    return;
                end

	            mach(app);
            
	            app.U=velox(i);

	            density(app);        
                    
                app.q = 0.5*app.rho*(app.U^2);
                app.q=app.q*QS;
                
                reynolds(app);
      
            %%
            
                if(app.iflow==1)  % Attached Flow (Type I)                                    
		            
                    if(i==1)
                        disp(' Attached Flow via the Laganelli and Wolfe method, AIAA paper 89-1064 ');
                    end 
            
		            attach(app);
	                
                end
             
                if(app.iflow==2)  % Separated Flow & Shockwaves: Compression Corner (IV or V)   
                    if(i==1)
                        disp(' Separated Flow & Shockwaves: Compression Corner  ');
                    end
                    if(app.icc==1)
                        if(i==1)
                            disp(' Plateau Region, Transonic Flow (Type IV) ');
                        end
			            cc_plateau_tf(app);
                    end
                    if(app.icc==2)
                        if(i==1)		
                            disp(' Reattachment Region, Transonic Flow (Type V) ');  
                        end
			            cc_reattachment_tf(app);
                    end
                    if(app.icc==3)
                        if(i==1)	
                            disp(' Plateau Region, Supersonic Flow (Type IV) '); 
                        end	
			            cc_plateau_supersonic(app);
                    end	
                    if(app.icc==4)
                        if(i==1)	
                            disp(' Separation or Reattachment Shockwave (Type V) ');
                        end
			            cc_separation_or_shockwave(app);
                    end
                    
                    if(app.icc>=3 && i==1)
                        angle=str2num(app.FrustumAngledegEditField.Value);
                        fprintf(' Frustum angle = %g deg \n',angle);
                        um=str2num(app.UpstreamMachNumber1EditField.Value);
                        fprintf(' Upstream Mach number = %g \n',um);          
                    end
                    
		            cc_auto(app);
                end

                if( app.iflow==3)  % Separated Flow & Shockwaves: Expansion Corner (II or III) 
                    
                        if(i==1)
                            disp(' Separated Flow & Shockwaves: Expansion Corner ');
                        end
                        
			            if(app.iex==1)
                            if(i==1)
					            disp(' Plateau Region, Transonic and Supersonic (Type II) ');
                            end
				            exp_plateau(app);
			            end
			            if(app.iex==2)	
				            if(i==1)
					            disp(' Reattachment Region, Transonic (Type III) ');
				            end
				            exp_reattachment(app);
			            end
			            exp_auto(app);
                end

                if( app.iflow==4)
                    protuberance(app);
                end    

                auto_spectrum(app);
          
                if(app.oafpl > maxoafpl )
                        app.maxfplcase=app.fpl;
                        app.maxG=app.G;
			            % maxq = app.q;
			            % maxMach=app.M;
			            % maxaltitude=app.altitude(i);
			            % maxikk = i;
			            maxoafpl=app.oafpl;
                end
                            
                if(i==1)		
                        disp('  ');
                        disp('  alt       Mach     q        c       OAFPL');
                        disp('  (ft)       No.    (psf)   (ft/sec)  (dB)');
                end
                    
                fprintf(' %7.0f  %6.2f  %8.1f  %8.1f  %6.1f \n',app.h,app.M,app.q,app.c(i),app.oafpl);

            end   % end altitude
            
            try
                disp(' ');
                disp('  Maximum FPL ');
                disp(' ');
                disp('  Freq(Hz)   FPL(dB) ');

                size(app.freq)
                size(app.maxfplcase)
            
                for i=1:length(app.freq)
                    fprintf(' %7.1f  %7.1f \n',app.freq(i),app.maxfplcase(i)); 
                end
            
            catch
               warndlg('Output error.  Check input parameters.');
               return;
            end
            
            % out1=sprintf('\n Overall FPL = %7.1f dB \n',maxoafpl);
            % disp(out1);
            
            f=app.freq;
            dB=app.maxfplcase;
            n_type=1;
            [fig_num]=fpl_plot(fig_num,n_type,f,dB);
            
            f=fix_size(f);
            dB=fix_size(dB);
            app.maxG=fix_size(app.maxG);
            
            app.maxG=app.maxG/12^4;
            
            data1=[f dB];
            app.aeroflow_fpl=data1;
           
            data2=[f app.maxG];
            app.maxG=data2;
          
            app.SaveButton.Enable='on';
            
            app.prms=10^(maxoafpl/20)*(2.9e-09);
            
            fprintf('\n Overall Pressure = %8.4g psi rms \n',app.prms);
              fprintf('                  = %8.4g Pa rms \n\n',app.prms*6891.2);

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            app.SaveButton.Enable='off';
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end

        % Value changed function: FlowRegimeListBox
        function FlowRegimeListBoxValueChanged(app, event)

            FlowRegimeChange(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            output_name=app.OutputArrayNameEditField.Value;

            Items = strtrim(app.OutputFunctionListBox.Items);
            im = strtrim(app.OutputFunctionListBox.Value);
            n = find(strcmp(Items, im));
            
            if(n==1)
                data=app.aeroflow_fpl;    
                fprintf('\n Output Array - freq(Hz) & FPL(dB) \n    %s \n\n',output_name); 
            else
                data=app.maxG;    
            end
            if(n==2)
                fprintf('\n Output Array - freq(Hz) & PSD(psi^2/Hz) \n    %s \n\n',output_name);      
            end
            if(n==3)
                data(:,2)=data(:,2)*6891.2^2;
                fprintf('\n Output Array - freq(Hz) & PSD(Pa^2/Hz) \n    %s \n\n',output_name);     
            end
                
            assignin('base', output_name, data);
            
            msgbox('Save Complete'); 

        end

        % Value changed function: SeparatedFlowCompressionCornerListBox
        function SeparatedFlowCompressionCornerListBoxValueChanged(app, event)

            aux_flow(app);

        end

        % Value changed function: AltitudeUnitListBox
        function AltitudeUnitListBoxValueChanged(app, event)
 
            app.SaveButton.Enable='off';
        end

        % Value changed function: VelocityUnitListBox
        function VelocityUnitListBoxValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
         
            app.SaveButton.Enable='off';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
 
            app.SaveButton.Enable='off';
        end

        % Value changed function: StationxrelativetofrontendEditField
        function StationxrelativetofrontendEditFieldValueChanged(app, event)
           
            app.SaveButton.Enable='off';
        end

        % Value changed function: QScaleEditField
        function QScaleEditFieldValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end

        % Value changed function: FrustumAngledegEditField
        function FrustumAngledegEditFieldValueChanged(app, event)
          
            app.SaveButton.Enable='off';
        end

        % Value changed function: UpstreamMachNumber1EditField
        function UpstreamMachNumber1EditFieldValueChanged(app, event)
            
            app.SaveButton.Enable='off';
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            
            flow.iflow = app.FlowRegimeListBox.Value;
            flow.iaux = app.SeparatedFlowCompressionCornerListBox.Value;

            flow.iu = app.UnitsListBox.Value;
            flow.ivel_unit = app.VelocityUnitListBox.Value;
            flow.ialt_unit = app.AltitudeUnitListBox.Value;
 
            flow.x = app.StationxrelativetofrontendEditField.Value;
            flow.angle = app.FrustumAngledegEditField.Value;
            flow.um = app.UpstreamMachNumber1EditField.Value;          

            try  

                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
 
                flow.FS=FS;
                flow.THM=app.THM;
            
            catch  
                warndlg('Input Array does not exist.  Try again.')
                return;
            end
            
            % % %
             
            structnames = fieldnames(flow, '-full'); % fields in the struct
              
            % % %
             
               [writefname, writepname] = uiputfile('*.mat','Save data as');
             
               %%%    writepfname = fullfile(writepname, writefname);
                
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
               
                elk=sprintf('%s%s',writepname,writefname);
             
                try
             
                    save(elk, 'flow'); 
             
                catch
                    warndlg('Save error');
                    return;
                end
             
            %%% SSS=load(elk)
             
            %%%%%%%%%%
            %%%%%%%%%%
             
            % Construct a questdlg with four options
            choice = questdlg('Save Complete.  Reset Workspace?', ...
                'Options', ...
                'Yes','No','No');
            % Handle response
            switch choice
                case 'Yes'
            %%        disp([choice ' Reseting'])
            %%        pushbutton_reset_Callback(hObject, eventdata, handles)
                    appdata = get(0,'ApplicationData');
                    fnsx = fieldnames(appdata);
                    for ii = 1:numel(fnsx)
                        rmappdata(0,fnsx{ii});
                    end
            end  

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
            disp(' ref 1');
             
            [filename, pathname] = uigetfile('*.mat');
             
            NAME = [pathname,filename];
             
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
             
            disp(' ref 2');
             
            k=length(structnames);
             
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
             
            disp(' ref 3');
             
            structnames
             
             
            % struct
             
            try
                flow=evalin('base','flow');
            catch
                warndlg(' evalin failed ');
                return;
            end
             
            flow
            
            try 
                app.SelectInputArrayListBox.Value=flow.FS;   
            catch
                disp(' error 1');
                return;
            end
            
            try
                    THM_trajectory=flow.THM;    
                    assignin('base',flow.FS,THM_trajectory); 
            catch
                disp(' error 2');    
            end    

            update(app);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
                app.FlowRegimeListBox.Value=flow.iflow;
            catch
            end
            
            try
                app.SeparatedFlowCompressionCornerListBox.Value=flow.iaux;     
            catch
            end
            
            try   
                app.UnitsListBox.Value=flow.iu;   
            catch
            end
            
            try
                app.VelocityUnitListBox.Value=flow.ivel_unit;   
            catch
            end
            
            try
                app.AltitudeUnitListBox.Value=flow.ialt_unit;
            catch
            end
            
            %%%%%%%%%
            
            try
                app.StationxrelativetofrontendEditField.Value=flow.x;    
            catch
            end
            
            try
                app.FrustumAngledegEditField.Value=flow.angle;   
            catch
            end
            
            try
                app.UpstreamMachNumber1EditField.Value=flow.um;     
            catch
            end
            
            %%%%%%%%%%%%

            FlowRegimeChange(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.aerodynamicflowUIFigure = uifigure('Visible', 'off');
            app.aerodynamicflowUIFigure.Name = 'aerodynamic flow';
            app.aerodynamic_flow_aver14byTomIrvineLabel = uilabel(app.aerodynamicflowUIFigure);
            app.aerodynamic_flow_aver14byTomIrvineLabel.Text = 'aerodynamic_flow_a   ver 1.4  by Tom Irvine     ';
            app.TabGroup = uitabgroup(app.aerodynamicflowUIFigure);
            app.InputTrajectoryTab = uitab(app.TabGroup);
            app.InputTrajectoryTab.Title = 'Input Trajectory';
            app.AltitudeUnitListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.AltitudeUnitListBoxLabel.Text = 'Altitude Unit';
            app.AltitudeUnitListBox = uilistbox(app.InputTrajectoryTab);
            app.AltitudeUnitListBox.Items = {'nmi ', 'feet', 'km  '};
            app.AltitudeUnitListBox.ValueChangedFcn = createCallbackFcn(app, @AltitudeUnitListBoxValueChanged, true);
            app.AltitudeUnitListBox.Value = 'nmi ';
            app.VelocityUnitListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.VelocityUnitListBoxLabel.Text = 'Velocity Unit';
            app.VelocityUnitListBox = uilistbox(app.InputTrajectoryTab);
            app.VelocityUnitListBox.Items = {'Mach  ', 'ft/sec', 'm/sec'};
            app.VelocityUnitListBox.ValueChangedFcn = createCallbackFcn(app, @VelocityUnitListBoxValueChanged, true);
            app.VelocityUnitListBox.Value = 'Mach  ';
            app.listbox_method = uilistbox(app.InputTrajectoryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputTrajectoryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputTrajectoryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputTrajectoryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputTrajectoryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.TheinputarraymusthavetwocolumnsaltitudevelocityLabel = uilabel(app.InputTrajectoryTab);
            app.TheinputarraymusthavetwocolumnsaltitudevelocityLabel.Text = 'The input array must have two columns:  altitude & velocity';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.StationxrelativetofrontendEditFieldLabel = uilabel(app.CalculateTab);
            app.StationxrelativetofrontendEditFieldLabel.Text = 'Station x relative to front end';
            app.StationxrelativetofrontendEditField = uieditfield(app.CalculateTab, 'text');
            app.StationxrelativetofrontendEditField.ValueChangedFcn = createCallbackFcn(app, @StationxrelativetofrontendEditFieldValueChanged, true);
            app.UnitsListBox = uilistbox(app.CalculateTab);
            app.UnitsListBox.Items = {'inch', 'meters'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'inch';
            app.QScaleEditFieldLabel = uilabel(app.CalculateTab);
            app.QScaleEditFieldLabel.Text = 'Q Scale';
            app.QScaleEditField = uieditfield(app.CalculateTab, 'text');
            app.QScaleEditField.ValueChangedFcn = createCallbackFcn(app, @QScaleEditFieldValueChanged, true);
            app.QScaleEditField.Value = '1';
            app.FrustumAngledegEditFieldLabel = uilabel(app.CalculateTab);
            app.FrustumAngledegEditFieldLabel.Text = 'Frustum Angle (deg)';
            app.FrustumAngledegEditField = uieditfield(app.CalculateTab, 'text');
            app.FrustumAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @FrustumAngledegEditFieldValueChanged, true);
            app.UpstreamMachNumber1EditFieldLabel = uilabel(app.CalculateTab);
            app.UpstreamMachNumber1EditFieldLabel.Text = 'Upstream Mach Number >=1';
            app.UpstreamMachNumber1EditField = uieditfield(app.CalculateTab, 'text');
            app.UpstreamMachNumber1EditField.ValueChangedFcn = createCallbackFcn(app, @UpstreamMachNumber1EditFieldValueChanged, true);
            app.FlowRegimeListBoxLabel = uilabel(app.CalculateTab);
            app.FlowRegimeListBoxLabel.Text = 'Flow Regime';
            app.FlowRegimeListBox = uilistbox(app.CalculateTab);
            app.FlowRegimeListBox.Items = {'Attached Flow (Type I)                                     ', 'Separated Flow & Shockwaves: Compression Corner (IV or V)  ', 'Separated Flow & Shockwaves: Expansion Corner   (II or III)', 'Protuberance, 45 deg Wedge'};
            app.FlowRegimeListBox.ValueChangedFcn = createCallbackFcn(app, @FlowRegimeListBoxValueChanged, true);
            app.FlowRegimeListBox.Value = 'Attached Flow (Type I)                                     ';
            app.CaculateButton = uibutton(app.CalculateTab, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.SeparatedFlowCompressionCornerListBoxLabel = uilabel(app.CalculateTab);
            app.SeparatedFlowCompressionCornerListBoxLabel.Text = 'Separated Flow, Compression Corner';
            app.SeparatedFlowCompressionCornerListBox = uilistbox(app.CalculateTab);
            app.SeparatedFlowCompressionCornerListBox.Items = {'Plateau Region, Transonic Flow (IV)                             ', 'Reattachment Region, Transonic Flow (V)                         ', 'Plateau Region, Supersonic Flow  (IV)                           ', 'Separation or Reattachment Shockwave (Local Supersonic Flow) (V)'};
            app.SeparatedFlowCompressionCornerListBox.ValueChangedFcn = createCallbackFcn(app, @SeparatedFlowCompressionCornerListBoxValueChanged, true);
            app.SeparatedFlowCompressionCornerListBox.Value = 'Plateau Region, Transonic Flow (IV)                             ';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.FrankenMethodButton = uibutton(app.ExportTab, 'push');
            app.FrankenMethodButton.ButtonPushedFcn = createCallbackFcn(app, @FrankenMethodButtonPushed, true);
            app.FrankenMethodButton.Text = 'Franken Method';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.OutputFunctionListBoxLabel = uilabel(app.ExportTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox = uilistbox(app.ExportTab);
            app.OutputFunctionListBox.Items = {'FPL (dB)         ', 'Pressure PSD (psi^2/Hz)', 'Pressure PSD (Pa^2/Hz)'};
            app.OutputFunctionListBox.Value = 'FPL (dB)         ';
            app.SaveModelButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.ReturnButton = uibutton(app.aerodynamicflowUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.aerodynamicflowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aerodynamic_flow_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.aerodynamicflowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.aerodynamicflowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.aerodynamicflowUIFigure)
        end
    end
end