classdef amplitude_conversion_utilities_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAmplitudeConversion          matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        amplitude_conversion_utilities_aver12byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SineTab                        matlab.ui.container.Tab
        SineAmplitudeEditField         matlab.ui.control.EditField
        SineAmplitudeEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.EditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        InputListBoxSine               matlab.ui.control.ListBox
        InputListBoxLabel              matlab.ui.control.Label
        SteadystateSineAmplitudeLabel  matlab.ui.control.Label
        SRSTab                         matlab.ui.container.Tab
        SRSAmpEditField                matlab.ui.control.EditField
        SRSAmpEditFieldLabel           matlab.ui.control.Label
        NaturalFrequencyHzEditField    matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        InputMetricListBox             matlab.ui.control.ListBox
        InputMetricListBoxLabel        matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        ResultsTextArea_SRS            matlab.ui.control.TextArea
        ResultsTextArea_SRSLabel       matlab.ui.control.Label
        CalculateButton_SRS            matlab.ui.control.Button
        SRSAmplitudeLabel              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function ChangeSine(app)
        
            app.ResultsTextArea.Enable='off'; 
            app.ResultsTextAreaLabel.Visible='off';
        
            n=strtrim(app.InputListBoxSine.Value); 
            
            s1='Displacement (peak-to-peak)';
            s2='Displacement (zero-to-peak)';
            s3='Velocity';
            s4='Acceleration';

            if(strcmp(n,'Displacement (peak-to-peak)'))
                app.SineAmplitudeEditFieldLabel.Text=s1; 
                app.ListBox.Items={'inch','mm'};
            end
            if(strcmp(n,'Displacement (zero-to-peak)'))
                app.SineAmplitudeEditFieldLabel.Text=s2;
                app.ListBox.Items={'inch','mm'};       
            end
            if(strcmp(n,'Velocity (zero-to-peak)'))
                app.SineAmplitudeEditFieldLabel.Text=s3; 
                app.ListBox.Items={'inch/sec','m/sec'};
            end
            if(strcmp(n,'Acceleration (zero-to-peak)'))
                app.SineAmplitudeEditFieldLabel.Text=s4; 
                app.ListBox.Items={'G','m/sec^2'};             
            end  
            
            app.ResultsTextArea.Visible='off';

        end
        
        function ChangeSRS(app)
            app.ResultsTextArea_SRSLabel.Visible='off';
            app.ResultsTextArea_SRS.Visible='off';
            app.ResultsTextArea_SRS.Enable='off';
            
            n=strtrim(app.UnitsListBox.Value);
            m=strtrim(app.InputMetricListBox.Value);
            
            s='none';
            
            if(strcmp(n,'accel (G) & vel (in/sec) & disp (in)'))  
                if(strcmp(m,'relative displacement'))  % rel disp
                    s='Relative Displacement (in)';
                end
                if(strcmp(m,'pseudo velocity'))  % pv
                    s='Pseudo Velocity (in/sec)';        
                end
                if(strcmp(m,'absolute acceleration'))  % accel
                    s='Acceleration (G)';        
                end
            end
            if(strcmp(n,'accel (G) & vel (m/sec) & disp (mm)'))     
                if(strcmp(m,'relative displacement'))  % rel disp
                    s='Relative Displacement (mm)';  
                end
                if(strcmp(m,'pseudo velocity'))  % pv
                    s='Pseudo Velocity (m/sec)';
                end
                if(strcmp(m,'absolute acceleration'))  % accel
                    s='Acceleration (G)';
                end
            end
            if(strcmp(n,'accel (m/sec^2) & vel (m/sec) & disp (mm)'))  
                if(strcmp(m,'relative displacement'))  % rel disp
                    s='Relative Displacement (mm)'; 
                end
                if(strcmp(m,'pseudo velocity'))  % pv
                    s='Pseudo Velocity (m/sec)';
                end
                if(strcmp(m,'absolute acceleration'))  % accel
                    s='Acceleration (m/sec^2)';
                end
            end
            %                      
            app.SRSAmpEditFieldLabel.Text=s;         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             ChangeSine(app);
             ChangeSRS(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
             closereq;
        end

        % Value changed function: InputListBoxSine
        function InputListBoxSineValueChanged(app, event)
            ChangeSine(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            n=strtrim(app.InputListBoxSine.Value);
            m=strtrim(app.ListBox.Value);
            
            amp=str2num(app.SineAmplitudeEditField.Value);
            freq=str2num(app.FrequencyHzEditField.Value);
            
            omega=2*pi*freq;
            om2=omega^2;
            
            mm_per_in=25.4;
            in_per_mm=1/mm_per_in;
            
            m_per_in=mm_per_in/1000;
            in_per_m=1/m_per_in;
            
            dzp_in=0;
            dzp_mm=0;
            dpp_in=0;
            dpp_mm=0;
            
            if(strcmp(n,'Displacement (peak-to-peak)'))
            %
               if(strcmp(m,'inch'))  % inch input
                   dpp_in=amp;
                   dpp_mm=amp*mm_per_in;       
               else
                   dpp_mm=amp;
                   dpp_in=amp*in_per_mm;     
               end
            %
               dzp_in=dpp_in/2;
               dzp_mm=dpp_mm/2;
            %
            end
            
            if(strcmp(n,'Displacement (zero-to-peak)'))
            %
               if(strcmp(m,'inch'))  % inch input
                   dzp_in=amp;
                   dzp_mm=amp*mm_per_in;       
               else
                   dzp_mm=amp;
                   dzp_in=amp*in_per_mm;     
               end
            %
            end
            %
            if(strcmp(n,'Velocity (zero-to-peak)'))
            %
               if(strcmp(m,'inch/sec'))  % in/sec input
                 v_ips=amp;
                 v_mps=amp*m_per_in;  
            %
               else  % m/sec
                 v_mps=amp; 
                 v_ips=amp*in_per_m;
            %
               end
            %
               dzp_in=v_ips/omega;
               dzp_mm=(v_mps/omega)*1000;
            %
            end
            %
            if(strcmp(n,'Acceleration (zero-to-peak)'))
            %
               if(strcmp(m,'G'))  % G input
                 a_g=amp;
                 a_mps2=amp*9.81; 
            %
               else  % m/sec
                 a_mps2=amp; 
                 a_g=amp/9.81;
            %
               end
            %
               dzp_in=(a_g/om2)*386;
               dzp_mm=(a_mps2/om2)*1000;
            %
            end
            %
            if(~strcmp(n,'Displacement (peak-to-peak)'))
               dpp_in=dzp_in*2;
               dpp_mm=dzp_mm*2;
            end
            %
            if(~strcmp(n,'Velocity (zero-to-peak)'))
               v_ips=omega*dzp_in;
               v_mps=omega*(dzp_mm)/1000;
            end
            %
            if(~strcmp(n,'Acceleration (zero-to-peak)'))
               a_g=om2*dzp_in/386;
               a_mps2=om2*dzp_mm/1000;
            end
            %
            
            s1=sprintf('Frequency = %8.4g Hz',freq);
            s2=sprintf('\n\n Displacement (peak-to-peak)');
            s3=sprintf('\n%8.4g in',dpp_in);
            s4=sprintf('\n%8.4g mm',dpp_mm);
            s5=sprintf('\n\n Displacement (zero-to-peak)');
            s6=sprintf('\n%8.4g in',dzp_in);
            s7=sprintf('\n%8.4g mm',dzp_mm);
            s8=sprintf('\n\n Velocity (zero-to-peak)');
            s9=sprintf('\n%8.4g in/sec',v_ips);
            s10=sprintf('\n%8.4g m/sec',v_mps);
            s11=sprintf('\n%8.4g cm/sec',v_mps*100);
            s12=sprintf('\n\n Accceleration (zero-to-peak)');
            s13=sprintf('\n%8.4g G',a_g);
            s14=sprintf('\n%8.4g m/sec^2',a_mps2);
            
            str=strcat( s1,s2,s3,s4,s5,s6,s7,s8,s9,s10,s11,s12,s13,s14);
            
            app.ResultsTextAreaLabel.Visible='on';
            app.ResultsTextArea.Visible='on';
            app.ResultsTextArea.Enable='on';
            app.ResultsTextArea.Value=str;
        end

        % Callback function
        function SineAmplitudeEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off'; 
            app.ResultsTextArea.Visible='off';
        end

        % Callback function
        function oldFrequencyHzEditFieldValueChanged(app, event)
EditFieldValueChanging
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
          
 
        end

        % Button pushed function: CalculateButton_SRS
        function CalculateButton_SRSPushed(app, event)


            n=strtrim(app.UnitsListBox.Value);
            m=strtrim(app.InputMetricListBox.Value);
            
            s=str2num(app.SRSAmpEditField.Value);
            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            
            omega=2*pi*fn;
            om2=omega^2;
            
            s1=sprintf('fn=%8.4g Hz',fn);
            
            if(strcmp(n,'accel (G) & vel (in/sec) & disp (in)'))  
                if(strcmp(m,'relative displacement'))  % rel disp (in)
                    rd=s;
                    pv=omega*rd;
                    ac=om2*rd/386;
                end
                if(strcmp(m,'pseudo velocity'))  % pv (in/sec)
                    pv=s;
                    rd=pv/omega;
                    ac=omega*pv/386;    
                end
                if(strcmp(m,'absolute acceleration'))  % accel (G)        
                    ac=s;
                    pv=386*ac/omega;          
                    rd=386*ac/om2;     
                end
                s2=sprintf('\n\n Relative Displacement \n = %8.4g in',rd);
                s3=sprintf('\n\n Pseudo Velocity \n = %8.4g in/sec',pv);
                s4=sprintf('\n\n Acceleration \n = %8.4g G',ac);
            end
            if(strcmp(n,'accel (G) & vel (m/sec) & disp (mm)'))     
                if(strcmp(m,'relative displacement'))  % rel disp (mm)
                    rd=s;        
                    pv=omega*rd/1000;  
                    ac=om2*rd/(9.81*1000);  
                end
                if(strcmp(m,'pseudo velocity'))  % pv (m/sec)
                    pv=s;
                    rd=1000*pv/omega;
                    ac=omega*pv/9.81;   
                end
                if(strcmp(m,'absolute acceleration'))  % accel (G)
                    ac=s;
                    pv=9.81*ac/omega;
                    rd=9.81*1000*ac/om2;
                end
                s2=sprintf('\n\n Relative Displacement \n = %8.4g mm',rd);
                s3=sprintf('\n\n Pseudo Velocity \n = %8.4g m/sec',pv);
                s4=sprintf('\n\n Acceleration \n = %8.4g G',ac);    
            end
            if(strcmp(n,'accel (m/sec^2) & vel (m/sec) & disp (mm)')) 
                if(strcmp(m,'relative displacement'))  % rel disp (mm)
                    rd=s;
                    pv=omega*rd/1000;
                    ac=om2*rd/1000; 
                end
                if(strcmp(m,'pseudo velocity'))  % pv (m/sec)
                    pv=s;        
                    rd=1000*pv/omega;
                    ac=omega*pv; 
                end
                if(strcmp(m,'absolute acceleration'))  % accel (m/sec^2)
                    ac=s;
                    pv=ac/omega;
                    rd=1000*ac/om2;
                end
                s2=sprintf('\n\n Relative Displacement \n = %8.4g mm ',rd);
                s3=sprintf('\n\n Pseudo Velocity \n = %8.4g m/sec ',pv);
                s4=sprintf('\n\n Acceleration \n = %8.4g m/sec^2 ',ac);        
            end
            
            str=strcat( s1,s2,s3,s4); 
        
            app.ResultsTextArea_SRS.Value=str;
            
            app.ResultsTextArea_SRS.Visible='on';
            app.ResultsTextArea_SRS.Enable='on';
            app.ResultsTextArea_SRSLabel.Visible='on';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ChangeSRS(app)
        end

        % Value changed function: InputMetricListBox
        function InputMetricListBoxValueChanged(app, event)
            ChangeSRS(app);
        end

        % Callback function
        function SRSAmpEditFieldValueChanged(app, event)

             app.ResultsTextArea_SRS.Enable='off';
        end

        % Callback function
        function NaturalFrequencyHzEditFieldValueChanged(app, event)
   
             app.ResultsTextArea_SRS.Enable='off';
        end

        % Value changing function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanging(app, event)
             app.ResultsTextArea.Visible='off';
        end

        % Value changing function: SineAmplitudeEditField
        function SineAmplitudeEditFieldValueChanging(app, event)
            app.ResultsTextArea.Visible='off';
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            app.ResultsTextArea_SRS.Visible='off';            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAmplitudeConversion = uifigure('Visible', 'off');
            app.UIAmplitudeConversion.Name = 'Amplitude Conversion';
            app.TabGroup = uitabgroup(app.UIAmplitudeConversion);
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.SteadystateSineAmplitudeLabel = uilabel(app.SineTab);
            app.SteadystateSineAmplitudeLabel.Text = 'Steady-state Sine Amplitude';
            app.InputListBoxLabel = uilabel(app.SineTab);
            app.InputListBoxLabel.Text = 'Input';
            app.InputListBoxSine = uilistbox(app.SineTab);
            app.InputListBoxSine.Items = {'Displacement (peak-to-peak)', 'Displacement (zero-to-peak)', 'Velocity (zero-to-peak)', 'Acceleration (zero-to-peak)'};
            app.InputListBoxSine.ValueChangedFcn = createCallbackFcn(app, @InputListBoxSineValueChanged, true);
            app.InputListBoxSine.Value = 'Displacement (peak-to-peak)';
            app.Label = uilabel(app.SineTab);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.SineTab);
            app.ListBox.Items = {'inch', 'mm'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'inch';
            app.ResultsTextAreaLabel = uilabel(app.SineTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.SineTab);
            app.CalculateButton = uibutton(app.SineTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineTab, 'text');
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanging, true);
            app.SineAmplitudeEditFieldLabel = uilabel(app.SineTab);
            app.SineAmplitudeEditFieldLabel.Text = 'Displacement (peak-to-peak)';
            app.SineAmplitudeEditField = uieditfield(app.SineTab, 'text');
            app.SineAmplitudeEditField.ValueChangingFcn = createCallbackFcn(app, @SineAmplitudeEditFieldValueChanging, true);
            app.SRSTab = uitab(app.TabGroup);
            app.SRSTab.Title = 'SRS';
            app.SRSAmplitudeLabel = uilabel(app.SRSTab);
            app.SRSAmplitudeLabel.Text = 'SRS Amplitude';
            app.CalculateButton_SRS = uibutton(app.SRSTab, 'push');
            app.CalculateButton_SRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SRSPushed, true);
            app.CalculateButton_SRS.Text = 'Calculate';
            app.ResultsTextArea_SRSLabel = uilabel(app.SRSTab);
            app.ResultsTextArea_SRSLabel.Text = 'Results';
            app.ResultsTextArea_SRS = uitextarea(app.SRSTab);
            app.UnitsListBoxLabel = uilabel(app.SRSTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SRSTab);
            app.UnitsListBox.Items = {'accel (G) & vel (in/sec) & disp (in)', 'accel (G) & vel (m/sec) & disp (mm)', 'accel (m/sec^2) & vel (m/sec) & disp (mm)'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'accel (G) & vel (in/sec) & disp (in)';
            app.InputMetricListBoxLabel = uilabel(app.SRSTab);
            app.InputMetricListBoxLabel.Text = 'Input Metric';
            app.InputMetricListBox = uilistbox(app.SRSTab);
            app.InputMetricListBox.Items = {'relative displacement', 'pseudo velocity', 'absolute acceleration'};
            app.InputMetricListBox.ValueChangedFcn = createCallbackFcn(app, @InputMetricListBoxValueChanged, true);
            app.InputMetricListBox.Value = 'relative displacement';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.SRSTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.SRSTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.SRSAmpEditFieldLabel = uilabel(app.SRSTab);
            app.SRSAmpEditFieldLabel.Text = 'Relative Displacement (in)';
            app.SRSAmpEditField = uieditfield(app.SRSTab, 'text');
            app.amplitude_conversion_utilities_aver12byTomIrvineLabel = uilabel(app.UIAmplitudeConversion);
            app.amplitude_conversion_utilities_aver12byTomIrvineLabel.Text = 'amplitude_conversion_utilities_a   ver 1.2  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIAmplitudeConversion, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIAmplitudeConversion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = amplitude_conversion_utilities_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIAmplitudeConversion)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIAmplitudeConversion)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAmplitudeConversion)
        end
    end
end