classdef beam_bending_FEA_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamBendingFEAUIFigure          matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.EditField
        NumberofElementsLabel           matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.EditField
        EditField_1Label                matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        ImageIBeam                      matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        MaterialAddedMassTab            matlab.ui.container.Tab
        PointMassLocationfromLeftEndinEditField  matlab.ui.control.EditField
        PointMassLocationfromLeftEndinEditFieldLabel  matlab.ui.control.Label
        PointMasslbmEditField           matlab.ui.control.EditField
        PointMasslbmEditFieldLabel      matlab.ui.control.Label
        NumberofPointMassesListBox      matlab.ui.control.ListBox
        NumberofPointMassesListBoxLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        UniformNonstructuralMasslbmEditField  matlab.ui.control.EditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        NaturalFrequenciesTab           matlab.ui.container.Tab
        UITable_fn                      matlab.ui.control.Table
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        CalculateNaturalFrequenciesButton  matlab.ui.control.Button
        PlotModeShapeButton             matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        BaseExcitationResponseTab       matlab.ui.container.Tab
        SetNodetoListBox                matlab.ui.control.ListBox
        SetNodetoListBoxLabel           matlab.ui.control.Label
        ResponseNodeEditField           matlab.ui.control.EditField
        ResponseNodeEditFieldLabel      matlab.ui.control.Label
        PlotListBox                     matlab.ui.control.ListBox
        PlotListBoxLabel                matlab.ui.control.Label
        NumberofModestoIncludeListBox   matlab.ui.control.ListBox
        NumberofModestoIncludeLabel     matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        TransmissibilityTab             matlab.ui.container.Tab
        OutputArrayPrefixEditField      matlab.ui.control.EditField
        OutputArrayPrefixEditFieldLabel  matlab.ui.control.Label
        TransOutputArraysReadOnlyTextArea  matlab.ui.control.TextArea
        TransOutputArraysReadOnlyTextAreaLabel  matlab.ui.control.Label
        OutputFrequencySpacingListBox   matlab.ui.control.ListBox
        OutputFrequencySpacingListBoxLabel  matlab.ui.control.Label
        EndFreqHzEditField              matlab.ui.control.EditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.EditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateBaseInputTrans         matlab.ui.control.Button
        PSDInputTab                     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        PSDInputTab_2                   matlab.ui.container.Tab
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculateTab_2                  matlab.ui.container.Tab
        PSDOutputArraysTextArea         matlab.ui.control.TextArea
        PSDOutputArraysTextAreaLabel    matlab.ui.control.Label
        OutputPSDArrayPrefixEditField   matlab.ui.control.EditField
        OutputPSDArrayPrefixLabel       matlab.ui.control.Label
        OutputFrequencySpacingListBox_psd  matlab.ui.control.ListBox
        OutputFrequencySpacingListBox_2Label  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SineSteadyStateTab              matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        ExcitationFreqHzEditField       matlab.ui.control.EditField
        ExcitationFreqHzEditFieldLabel  matlab.ui.control.Label
        BaseAccelerationGEditField      matlab.ui.control.EditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        CalculateBaseInputSine          matlab.ui.control.Button
        SettoFirstfnButton              matlab.ui.control.Button
        ArbitraryTab                    matlab.ui.container.Tab
        RainflowFatigueButton           matlab.ui.control.Button
        ResponseResultsReadOnlyTextArea  matlab.ui.control.TextArea
        ResponseResultsReadOnlyLabel    matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateBaseInputArbitrary     matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecaccelGLabel  matlab.ui.control.Label
        MDOFSRSTab_2                    matlab.ui.container.Tab
        ResultsTextArea_srs             matlab.ui.control.TextArea
        ResultsTextAreaLabel_srs        matlab.ui.control.Label
        SelectInputArrayListBox_srs     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_2  matlab.ui.control.Label
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        UpdateListButton_srs            matlab.ui.control.Button
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        text1_srs                       matlab.ui.control.Label
        listbox_method_srs              matlab.ui.control.ListBox
        CalculateBaseInputMDOFSRS       matlab.ui.control.Button
        AppliedForceResponseTab         matlab.ui.container.Tab
        InputForceNodeEditField         matlab.ui.control.EditField
        InputForceNodeEditFieldLabel    matlab.ui.control.Label
        SetResponseNodetoListBox        matlab.ui.control.ListBox
        SetResponseNodetoListBoxLabel   matlab.ui.control.Label
        ResponseNodeEditField_frf       matlab.ui.control.EditField
        ResponseNodeEditField_2Label    matlab.ui.control.Label
        NumberofModestoIncludeListBox_frf  matlab.ui.control.ListBox
        NumberofModestoIncludeListBox_2Label  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        TransferFunctionTab             matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        CalculateFRF                    matlab.ui.control.Button
        OutputFrequencySpacingListBox_frf  matlab.ui.control.ListBox
        OutputFrequencySpacingListBox_2Label_2  matlab.ui.control.Label
        EndFreqHzEditField_frf          matlab.ui.control.EditField
        EndFreqHzEditField_2Label       matlab.ui.control.Label
        StartFreqHzEditField_frf        matlab.ui.control.EditField
        StartFreqHzEditField_2Label     matlab.ui.control.Label
        SaveFRFTab                      matlab.ui.container.Tab
        SaveButton_2                    matlab.ui.control.Button
        FRFTypeListBox                  matlab.ui.control.ListBox
        FRFTypeListBoxLabel             matlab.ui.control.Label
        FormatListBox                   matlab.ui.control.ListBox
        FormatListBoxLabel              matlab.ui.control.Label
        FRFOutputArrayNameEditField     matlab.ui.control.EditField
        FRFOutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SineTimeHistoryTab              matlab.ui.container.Tab
        SampleRateEditField_force       matlab.ui.control.EditField
        DurationsecEditField_2Label_3   matlab.ui.control.Label
        FreqEditField                   matlab.ui.control.EditField
        DurationsecEditField_2Label_2   matlab.ui.control.Label
        DurationsecEditField_force_sine  matlab.ui.control.EditField
        DurationsecEditField_2Label     matlab.ui.control.Label
        ForcelbfEditField               matlab.ui.control.EditField
        ForcelbfEditFieldLabel          matlab.ui.control.Label
        SetfnButton                     matlab.ui.control.Button
        CalculateButton_force_sine      matlab.ui.control.Button
        NodePositionTab                 matlab.ui.container.Tab
        NodeNumberPositionLabel         matlab.ui.control.Label
        UITable_nodes                   matlab.ui.control.Table
        ExportTab                       matlab.ui.container.Tab
        NaturalFrequenciesEditField     matlab.ui.control.EditField
        NaturalFrequenciesEditFieldLabel  matlab.ui.control.Label
        ModeShapesEditField             matlab.ui.control.EditField
        ModeShapesEditFieldLabel        matlab.ui.control.Label
        TransferFunctionforAppliedForceButton  matlab.ui.control.Button
        StiffnessArrayEditField         matlab.ui.control.EditField
        StiffnessArrayEditFieldLabel    matlab.ui.control.Label
        MassArrayEditField              matlab.ui.control.EditField
        MassArrayEditFieldLabel         matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        beam_bending_FEA_aver19byTomIrvineLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        BC                 % Boundary condition
        C                  % Modal constant
        E                  % Elastic modulus
        FullModeShapes     %
        HM_accel           %
        HM_rd              %
        HM_stress          %       
        HM_stress_response %
        Kwd                %
        Kww                %
        L                  % Length
        LBC                % Left boundary condition
        ModeShapes         % Mode Shapes
        ModeShapes_corrected  %
        MOI                % Area moment of intertia
        MST                % Mode Shapes Transpose
        Mwd                %   
        Mww                %
        RBC                % Right boundary condition
        T1                 %
        T2                 %
        THM                %
        TotalModeShapes    %
        TT                 %
        TT1                %
        TT2                %
        TZ_tracking_array  %
        ZZ                 % Mode shape

        absolute_accel   % 
        acc              %
        acc_trans        %
        acceleration     %
        adisp            %
        atrans           %
        bending_moment   % 
        bending_stress   % 
        beta             % eigenvalue parameter
        cdof             %
        chplot           % 
        cna              % Distance to neutral axis
        constraint_matrix %
        ddd              %
        ddisp            %
        dm               % Damping
        dof              % degrees-of-freedom
        dof_status       % dof status
        dt               %
        drot_dof         %
        dx               % element length array
        ea               %
        eac              %
        f                %
        fmax             %
        fig_num          %
        fn               % Natural frequencies
        free_dof_array   %
        freq             %
        fmin           %
        global_mass      %
        global_mass_unc  %  
        global_stiff     %
        global_stiff_unc %
        iflag            %
        imat             %
        ipart            %
        iu               % iu=1 for English units
        kflag            %
        mass             %
        mass_unc         %
        method           %
        mode             %
        na               %
        nde              %
        ndof             %
        ne               % number of elements
        nem              %
        nem_ea           %
        nff              %
        ngw              %
        nnn              %
        nnn_max          %
        node             %
        node_near        %
        np               %
        nt               %
        num              %
        num_nodes        %
        num_nodes_Tz_free %
        num_columns      %
        number_modes     %

        omega            %
        omegad           %
        omegan           % Angular natural frequencies
        omn2             %
        part             % participation factor
        pomn2            %

        ppp_a            %
        ppp_pv           % 
        ppp_rd           %
        ppp_st           %

        pv               %
        pv_trans         %

        qqq_a            %
        qqq_pv           % 
        qqq_rd           %
        qqq_st           %

        rd               %
        rdtrans          %
        rd_trans         %
        relative_disp    %
        relative_vel     % 
        response_dof     %
        retdof           %
        rho              % Mass density
        rot_dof          %
        rot_constraint_matrix %
        rs               %
        s1               % modal frequency 1
        s2               % modal frequency 2
        s3               % modal frequency 3
        s4               % modal frequency 4
        s5               % modal frequency 5
        s6               % modal frequency 6          
        s7               % modal frequency 7
        s8               % modal frequency 8
        s9               % modal frequency 9
        s10              % modal frequency 10
        s11              % modal frequency 11
        s12              % modal frequency 12  
 

        shear_force      % 
        sq_mass          % sqrt of mass
        stiff            %
        stiff_unc        %
        strans           % 
        stress           %
        stress_max       %
        stress_trans     %
        tdof             % translational dof
        titlea           %
        tracking_array   %
        x                % x length along beam for mode shap        
        xx               % x length along beam for mode shapes
        xxr              % 
        y                % Mode shape

        accel_psd        %
        pv_psd           %
        rd_psd           %
        stress_psd       % 
        itype            %
        scale            %
        LU               %
        LV               %
        qflag            %
        kk               %
        sr               %

        acc_max          %
        rd_max           %
        pv_max           %

        node_acc         %
        node_rd          %

        column_st        %

        itrans           %

        max_st           %
        max_a            %
        max_pv           %
        max_rd           %

        R_tracking_array  %

        num_Tz           %

        stress_f_mag
        stress_f_mag_phase
        stress_f_mag_complex

        a_f_mag
        a_f_mag_phase
        a_f_mag_complex

        v_f_mag
        v_f_mag_phase
        v_f_mag_complex

        d_f_mag
        d_f_mag_phase
        d_f_mag_complex

        tpi

        oct96
        oct48

    end
    
    methods (Access = private)

        function SetResponseNodeFRF(app)

            Items = strtrim(app.SetResponseNodetoListBox.Items);
            im = strtrim(app.SetResponseNodetoListBox.Value);
            i = find(strcmp(Items, im));
        
            if(i==1)
                app.ResponseNodeEditField_frf.Value=sprintf('%d',round(1+(app.ne/2)));
            end
            if(i>=2 && i<=4)
                try
                    B=app.FullModeShapes(:,i-1);
                    [~,I]=max(abs(B));
                    app.ResponseNodeEditField_frf.Value=sprintf('%d',I);
                catch
                end
            end

            % retdof_a(app);

        end

        function tracking(app)

            %       total_dof=app.num_nodes*2;
                
            app.R_tracking_array=zeros(app.num_nodes,1);

            %        rot_dof;

            sz=size(app.rot_constraint_matrix);
            irc=sz(1);

            num_Ur=2*app.num_nodes-irc;
               
            app.rot_constraint_matrix=app.constraint_matrix;
                
            %        constraint_matrix;
                
            length(app.constraint_matrix(:,1))
                
            for i=length(app.constraint_matrix(:,1)):-1:1
                if(app.constraint_matrix(i,3)==0)
                    app.rot_constraint_matrix(i,:)=[];
                end
            end
                
            bbb=(1:num_Ur);
                
            for i=1:app.num_Tz
                [Lia,Locb] =ismember(app.TZ_tracking_array,bbb);
                if(Lia==1)
                    bbb(Locb)=[];
                end
            end
                
            %%        fprintf(' app.num_nodes=%d  num_Ur=%d \n',app.num_nodes,num_Ur);
            ccc=app.rot_constraint_matrix(:,1);
                    
            kv=1;
            
            for jj=1:app.num_nodes    
                if(ismember(jj,ccc))
            %                fprintf(' jj=%d  included \n',jj);
                else    
            %                fprintf(' jj=%d  kv=%d \n',jj,kv);
                            app.R_tracking_array(jj)=bbb(kv);
                            kv=kv+1;
                end
            end
        end    

        function retdof_a(app)

            disp('ret dof')

            app.node=str2num(app.ResponseNodeEditField.Value);
             
            ij=app.TZ_tracking_array(app.node);

            nidof=0;

            for j=1:length(app.ngw)
               
                if(app.ngw(j)==ij)
                    nidof=j;
                    break;
                end
                  
            end

            nidof
            app.nem

            app.retdof=nidof-app.nem;

            fprintf('app.retdof=%d\n',app.retdof);

        end

        function SetResponseNode(app)

            Items = strtrim(app.SetNodetoListBox.Items);
            im = strtrim(app.SetNodetoListBox.Value);
            i = find(strcmp(Items, im));
        
            if(i==1)
                app.ResponseNodeEditField.Value=sprintf('%d',round(1+(app.ne/2)));
            end
            if(i>=2 && i<=4)
                try
                    B=app.FullModeShapes(:,i-1);
                    [~,I]=max(abs(B));
                    app.ResponseNodeEditField.Value=sprintf('%d',I);
                catch
                end
            end

            retdof_a(app);

        end    


        function FEA_base_arbitrary(app)

            app.rd=[];
            app.acc=[];
            app.stress=[];
            app.pv=[];
            
            app.nt=length(app.THM(:,1));
            
            app.dt=mean(diff(app.THM(:,1)));
            app.sr=1/app.dt;

            sz=size(app.Mww);
            app.nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            progressbar;
            
            disp(' Integrating acceleration ');
           
            % [dvelox]=zeros(app.nt,1);
             [app.ddisp]=zeros(app.nt,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Modal Transient
            %
            clear omegad;

            app.nff=min([ length(app.omegan)  length(app.dm)  ]);


            app.omegad=zeros(app.nff,1);
            
            for i=1:app.nff
                app.omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
            end
            %

            progressbar(0.001);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
            ffn=app.omegan/(2*pi);
            
            num_modes=length(ffn);
            
            if(num_modes==0)
               out1=sprintf(' Increase input data sample rate >= %8.3g',10*ffn(1));
               warndlg(out1); 
               return; 
            end
            
            num_modes=str2num(app.NumberofModestoIncludeListBox.Value);
            
            noo=num_modes;
            
            for i=1:noo
                
                if(app.sr<10*ffn(i))
                   num_modes=i-1;
                   warndlg(sprintf('\n Number of included modes = %d \n due to input data sample rate limitation. ',num_modes));   
                   break; 
                end
                
            end

            progressbar(0.002);

            fprintf('\n sample rate = %8.4g samples/sec \n',app.sr);
            
            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:num_modes 
               fprintf(' %d.  %8.3g  %6.3g \n',i,ffn(i),app.dm(i)); 
            end
           
            Q=app.ModeShapes(:,1:num_modes);
            QT=Q';
            
            ff=zeros(app.nem,1);

            fq=app.THM(:,2);

            for i=1:length(fq)
                ff(1:app.nem,i)=fq(i);
            end
            
            disp(' ');
            disp(' Calculating FP ');
            
            QTMwd=QT*app.Mwd;
            FP=-QTMwd*ff;
            
            disp(' Completed FP ')

            progressbar(0.01);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %
            [a1,a2,df1,df2,df3,~,~,~,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(num_modes,app.omegan,app.dm,app.dt);
            %
            tic

            progressbar(0.05);

            ntt=length(FP);
    
            [~,nx]=enforced_motion_modal_transient_engine_fea_beam_alt_np...
                            (FP,a1,a2,df1,df2,df3,af1,af2,af3,ntt,num_modes);

            disp('Completed enforced motion')

            progressbar(0.1);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ntt=length(app.THM(:,2));

            Ud = zeros(app.nem, ntt);
            % Ua = Ud*0;

            % for i=1:app.nem
            %    Ua(i,:)=app.THM(:,1)';
            % end

            clear app.THM;

            progressbar(0.15);
              
            disp(' ref 21')

            U=app.TT*[Ud; app.ModeShapes(:,1:num_modes)*nx'];   
            % U_a=app.TT*[Ua; app.ModeShapes(:,1:num_modes)*naa'];  

            clear nx;


            nu=length(U(:,1));                
            Ur=zeros(nu,ntt);
            % Ur_a=Ur*0;

            % sz=size(U);

            disp('ref 22')

            Ur(app.ngw, :) = U;

            clear U;

            % Ur_a(app.ngw, :) = U_a;
            progressbar(0.2);


            disp('TZ_tracking_array')

            app.rd=zeros(ntt,app.num_nodes);
            % app.acc=zeros(ntt,app.num_nodes);

            progressbar(0.5);

            NLT=length(app.TZ_tracking_array);

            for i=1:NLT
                ij = app.TZ_tracking_array(i);
                % app.acc(:, i) = Ur_a(ij,:)';
                app.rd(:, i) = Ur(ij,:)';
            end
            progressbar(0.55);

            
            app.rd=app.rd*app.scale;

            cnaE=app.cna*app.E;

            % app.rd(j,ia)
            %        app.stress(j,i)=cnaE*(B*[app.rd(j,ia) app.rd(j,ib) app.rd(j,ic) app.rd(j,id) ]')';

            Ur=Ur';
            
            size(Ur)

            disp('Calculating stress')

            progressbar(0.57);

            app.stress=zeros(ntt,app.ne);

            delta=(0.75-0.57);
            
            for nk=1:app.num_nodes-1

                aq=0.57+delta*1/(app.num_nodes-1);
                progressbar(aq);

                left_node=nk;
                right_node=nk+1;
            
                LL=abs(app.xx(left_node)-app.xx(right_node));
                    
                [B]=beam_stress_B(0,LL);
                    
                TL=app.TZ_tracking_array(left_node);
                TR=app.TZ_tracking_array(right_node);

                URL=zeros(ntt,1);
                URR=zeros(ntt,1);
                    
                    if(app.R_tracking_array(left_node)~=0)
                        RL=app.R_tracking_array(left_node);
                        URL=Ur(:,RL);
                    end
                    if(app.R_tracking_array(right_node)~=0)
                        RR=app.R_tracking_array(right_node);
                        URR=Ur(:,RR);            
                    end

                d = transpose([Ur(:, TL) URL Ur(:, TR) URR]);
                app.stress(:, nk) = cnaE * B * d*app.scale;
                fprintf(' %d  %8.4g \n',nk,std(app.stress(:, nk)));

            end
            progressbar(0.76);

           % stress at right end  
           app.stress(:,end+1)=app.stress(:,end);
               
           
            toc     
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.iu==2)
                app.rd=app.rd*1000;
            end

            app.fig_num=30;
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            title('Base Input');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.node_near=str2num(app.ResponseNodeEditField.Value);

    
            rel_disp=[app.THM(:,1) app.rd(:,app.node_near)];

            clear accel;

          
            [app.pv]=differentiate_function(rel_disp(:,2),app.dt);
            [accel]=differentiate_function(app.pv,app.dt);  % will be converted to absolute
            progressbar(0.85);
 
            app.pv=fix_size(app.pv);
            accel=fix_size(accel);

            accel=accel/app.scale;
            accel=accel+app.THM(:,2);  % convert to absolute


            accel=[app.THM(:,1) accel];

            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(accel(:,1),accel(:,2));
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            out1=sprintf('Acceleration at node %d ',app.node);
            title(out1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            figure(app.fig_num);
            app.fig_num=app.fig_num+1;

            plot(rel_disp(:,1),app.pv);

            grid on;
            xlabel('Time (sec)');            

            if(app.iu==1)
                ylabel('Rel Vel (ips)');
                DV='ips';
            else
                ylabel('Rel Vel (cm/sec)');
                app.pv=app.pv/10;
                DV='cm/sec';
            end


            out1=sprintf('Relative Velocity at node %d ',app.node);
            title(out1);

            if(app.iu==2)
                rel_disp(:,2)=rel_disp(:,2)*1000;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(rel_disp(:,1),rel_disp(:,2));
            grid on;
            xlabel('Time (sec)');
            
            if(app.iu==1)
                ylabel('Rel Disp (in)');
                DQ='in';
            else
                ylabel('Rel Disp (mm)');
                DQ='mm';
            end
            out1=sprintf('Relative Displacement at node %d ',app.node);
            title(out1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ns=app.node;  % stress is by element

            if(ns==app.num_nodes)
                ns=app.num_nodes-1;
            end    

            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            ttt=rel_disp(:,1);
            plot(ttt,app.stress(:,ns));
            grid on;
            xlabel('Time (sec)');
            
            if(app.iu==1)
                ylabel('Stress (psi)');
                DS='psi';
            else
                ylabel('Stress (Pa)');
                DS='Pa';
            end

            eee=app.node;
            if(eee>app.ne)
                eee=app.ne;
            end
            out1=sprintf('Stress at Element %d ',eee);
            title(out1);           

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            a2=accel(:,2);
            r2=rel_disp(:,2);
            st2=app.stress(:,ns);

            ss=sprintf(' Accel (G)  std=%7.3g\n max=%7.3g   min=%7.3g \n ',std(a2),max(a2),min(a2));

            ss=sprintf('%s \n Rel Vel (%s)  std=%7.3g\n max=%7.3g   min=%7.3g \n ',ss,DV,std(app.pv),max(app.pv),min(app.pv));
            ss=sprintf('%s \n Rel Disp (%s)  std=%7.3g\n max=%7.3g   min=%7.3g ',ss,DQ,std(r2),max(r2),min(r2));
            ss=sprintf('%s \n\n Stress (%s)  std=%7.3g\n max=%7.3g   min=%7.3g ',ss,DS,std(st2),max(st2),min(st2));

            progressbar(0.9);

            [~,scolumn,svalue]=find_max_2d_array(app.stress);

            mss=sprintf('Max abs stress = %7.3g %s \n at Element %d',svalue,DS,scolumn);

            ss=sprintf('%s \n\n %s',ss,mss);

            output_name1=sprintf('accel_node_%d',app.node);
            output_name2=sprintf('rel_disp_node_%d',app.node);
            output_name3=sprintf('stress_node_%d',app.node);

            sq=sprintf('%s\n %s\n %s\n',output_name1,output_name2,output_name3);
         
            app.ResponseResultsReadOnlyTextArea.Value=sprintf('%s \n\n Output arrays \n %s',ss,sq);
            
            assignin('base', output_name1, accel);
            assignin('base', output_name2, rel_disp);
            assignin('base', output_name3, [ttt,app.stress(:,ns)]);

            progressbar(1);

        end    

        function Partition(app)
            %  ea=[1 2]  dof with enforced

            % num total dof
            % nem number of dof with enforced acceleration

            app.cdof=[];
            app.free_dof_array=[];
            app.tracking_array=[];
            app.TZ_tracking_array=[];
            app.constraint_matrix=[];
            app.ea=[];

            app.TT=[];
            app.T1=[];
            app.T2=[];
            app.Mwd=[];
            app.Mww=[];
            app.Kwd=[];
            app.Kww=[];

            app.rot_dof=[];

            if(isempty(app.mass_unc))
                warndlg('Calcuate Natural Frequencies First');
                return;
            end    

            try
                pmass=app.mass_unc;
                pstiff=app.stiff_unc;
            catch
                warndlg('Calcuate Natural Frequencies First');
                return;
            end

            % mass
            % stiff
            
            
            sz=size(pmass);
            app.dof=sz(1);
            
            app.tracking_array=zeros(app.dof,1);
            app.free_dof_array=zeros(app.dof,1);
            
            for i=1:app.dof
                app.tracking_array(i)=i;
                app.free_dof_array(i)=i;
            end
            
            app.num_nodes_Tz_free=round(app.dof/2);
            
            app.TZ_tracking_array=zeros(app.num_nodes_Tz_free,1);
            
            for i=1:app.num_nodes_Tz_free
               app.TZ_tracking_array(i)=2*i-1;
            end

            disp('app.tracking_array')
            app.TZ_tracking_array

            % disp('app.num_nodes_Tz_free')
            % app.num_nodes_Tz_free

            % disp('app.dof_status')
            % app.dof_status

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            
            for i=1:app.num_nodes_Tz_free
                
                if( app.dof_status(2*i-1)==0 || app.dof_status(2*i)==0)
                    app.constraint_matrix(k,:)=[i 0 0];
                    
                    if(app.dof_status(2*i-1)==0)
                        app.constraint_matrix(k,2)=1;
                    end
                    if(app.dof_status(2*i)==0)
                        app.constraint_matrix(k,3)=1;
                    end     
                    
                    k=k+1;
                end    
                    
            end
            
            % disp('app.constraint_matrix')
            % app.constraint_matrix

            
            k=1;
            
            ibc=1;
            
            n=length(app.constraint_matrix(:,1));
                
            for i=1:n
                
                if(app.constraint_matrix(i,2)==1)
                    app.ea(k)=2*app.constraint_matrix(i,1)-1;
                    k=k+1;
                end
                    
                if(app.constraint_matrix(i,3)==1)
                    app.rot_constraint_matrix(ibc,1)=app.constraint_matrix(i,1);
                    app.rot_constraint_matrix(ibc,2)=0;
                    app.rot_constraint_matrix(ibc,3)=app.constraint_matrix(i,3);
                    ibc=ibc+1;
                end
                        
            end
            
            ibc=ibc-1;
            
            % constraint_matrix
            % ea
            
            if(ibc>=1)
            %    rot_constraint_matrix
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sz=size(app.constraint_matrix);
            
            nc=sz(1);

            fprintf('\n nc=%d \n',nc)
            
            k=1;
            for i=1:nc 
                
                 cmn=app.constraint_matrix(i,1);
                
                 if(app.constraint_matrix(i,2)==1)
                    app.cdof(k)=2*cmn-1;
                    k=k+1;
                 end    
                 if(app.constraint_matrix(i,3)==1)
                    app.cdof(k)=2*cmn;
                    k=k+1;
                 end   
            end
            
            app.cdof=sort(app.cdof,'descend');

            % disp('app.cdof')
            % app.cdof

            % disp('app.free_dof_array')
            % app.free_dof_array
            
            for i=1:length(app.cdof)
                % fprintf(' i=%d  cdof=%d  free_dof_array=%d\n',i,app.cdof(i),length(app.free_dof_array));
                app.free_dof_array(app.cdof(i))=[];
            end    
            
            % cdof
            % free_dof_array
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            for i=1:ibc 
                 if(app.rot_constraint_matrix(i,3)==1)
                    app.rot_dof(k)=2*app.rot_constraint_matrix(i,1);
                    k=k+1;
                 end
            end
            
            try
                app.rot_dof=sort(app.rot_dof);
                ibc=length(app.rot_dof);  
            catch
                app.rot_dof=0;
                ibc=0;
            end          
  
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
          
            app.ea=fix_size(app.ea);

            sz=size(app.ea);

            app.eac=zeros(sz(1),3);
            
            app.eac(:,1)=app.ea;

            % disp('app.ea')
            % app.ea
            
            %  rot_dof
            
            if(ibc>=1)  % correct ea matrix
                
                app.rot_dof=sort(app.rot_dof,'descend');

                try
                
                    for i=1:ibc
                        for j=1:length(app.ea)
                            if(app.ea(j)>app.rot_dof(i))
                                app.ea(j)=app.ea(j)-1;
                %%                out7=sprintf(' %d %d ',ea(j),rot_dof(i));
                %%                disp(out7);
                            end
                        end
                    end

                catch
                    app.ea
                    app.rot_dof
                    warndlg('Error: ref 3');
                    return;
                end
                
                app.drot_dof=sort(app.rot_dof,'descend');
                
                for i=1:ibc
                  app.tracking_array(app.drot_dof(i))=[];           
                end   
                
                for i=1:ibc
                    for j=1:app.num_nodes_Tz_free
                        if( app.TZ_tracking_array(j)>app.rot_dof(i) )
                               app.TZ_tracking_array(j)=app.TZ_tracking_array(j)-1;
                        end       
                    end
                end    
                
            end
            
            
            app.eac(:,2)=app.ea;
            
            for i=1:length(app.ea)
                app.eac(i,3)=app.eac(i,2)-app.eac(i,1);
            end
                
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
            
            if(app.nem_ea==0)
                warndlg('No drive dofs');
                return;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(ibc>=1)
                sz=size(app.rot_constraint_matrix);
            else
                sz(1)=0;
            end
                
            for i=sz(1):-1:1
                if(app.rot_constraint_matrix(i,3)==1)
                    p=2*app.rot_constraint_matrix(i,1);
                     pmass(:,p)=[];
                     pmass(p,:)=[];
                    pstiff(:,p)=[];
                    pstiff(p,:)=[];      
                end
            end
            
            sz=size(pmass);
            app.num=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            etype=1;
             
            % num total dof
            % nem number of dof with enforced acceleration
             
            disp(' Track Changes ');  
            
            % num = total dof - constrained rotational dof
            % ea = Tz dof enforced acceleration
            
            % fprintf(' nem=%d  num=%d  \n',nem,num);
            % ea
            
            [app.ngw]=track_changes(app.nem_ea,app.num,app.ea);
             
            dtype=2; % display partitioned matrices
            
            %% out1=sprintf('\n ** num=%d  nem=%d \n',num,nem);
            %% disp(out1);
            
            
            fprintf(' ibc=%d \n',ibc);
            
            disp(' Enforced Partition Matrices ');    
            
            [app.TT,app.T1,app.T2,app.Mwd,app.Mww,app.Kwd,app.Kww]=...
                            enforced_partition_matrices(app.num,app.ea,pmass,pstiff,etype,dtype);

            sz=size(app.Mwd);

            app.nem=sz(2);
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            disp(' Generalized Eigen ');            
                        
            [app.fn,app.omega,app.ModeShapes,app.MST]=Generalized_Eigen(app.Kww,app.Mww,2);   

            app.ipart=1;

            tracking(app);

            pre_common(app);
                              
        end

        function update_th(app)
            CloseFigures(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox.Value=app.SelectInputArrayListBox.Items{1};
            catch
            end           
            try
                app.listbox_method.Value=app.listbox_method.Items{1};
            catch
            end
        end

        function ClearResults(app)
               
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off'; 
            app.ResultsTextArea_srs.Visible='off';
            app.ResultsTextAreaLabel_srs.Visible='off'; 
        end    


        function PointMass(app)
        
            n=str2num(app.NumberofPointMassesListBox.Value);
        
            app.PointMasslbmEditField.Visible='off';
            app.PointMassLocationfromLeftEndinEditField.Visible='off';
            app.PointMasslbmEditFieldLabel.Visible='off';
            app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='off';
            
            if(n==1)
                app.PointMasslbmEditField.Visible='on';
                app.PointMassLocationfromLeftEndinEditField.Visible='on';
                app.PointMasslbmEditFieldLabel.Visible='on';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='on';                 
            end
            
        end        

       function update_psd(app)
            CloseFigures(app);
            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density_alt();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end       

        function ChangeUnits(app)
            app.ipart=0;

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.scale=386;
                app.UITable_nodes.ColumnName = {'x (in)'; ''};
                app.LengthinEditFieldLabel.Text='Length (in)';
                % app.ResponseLocationfromLeftEndLabel.Text='Distance from Left End (in)';
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            else
                app.scale=9.81;
                app.UITable_nodes.ColumnName = {'x (m)'; ''};
                app.LengthinEditFieldLabel.Text='Length (m)'; 
                % app.ResponseLocationfromLeftEndLabel.Text='Distance from Left End (m)'; 
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (m/sec)'; 'Wavenumber (rad/m)'};
            end    

            if(app.iu==1)
                app.scale=386;
                app.LU='in';
                app.LV='ips';
            else
                app.scale=9.81;
                app.LU='m';
                app.LV='m/sec';
            end    

            
            Clearfn(app);
            ChangeCrossSection(app);  
            ChangeMaterials(app);
        end    

       function update_srs(app)

            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox_srs.Items=unique(sort(cell_array)); 
            try
                app.SelectInputArrayListBox_srs.Value=app.SelectInputArrayListBox_srs.Items{1};
            catch
            end
            try
                app.listbox_method_srs.Value=app.listbox_method_srs.Items{1};
            catch
            end
        end 

        function ChoosePlots(app)
            Items = strtrim(app.PlotListBox.Items);
            im = strtrim(app.PlotListBox.Value);
            app.chplot = find(strcmp(Items, im));

            % app.NodeNumberEditField.Visible='off';
            % app.ResponseLocationfromLeftEndLabel.Visible='off';
            % app.ScriptwillfindnearestnodelocationLabel.Visible='off';

            app.ipart=0;
        end    

        function Clearfn(app)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';            
            app.UITable_fn.Visible='off';
            app.PlotModeShapeButton.Visible='off';    
            app.fn=0;
            app.ipart=0;
        end   
        
        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6';'Mode 7';'Mode 8'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end        

        function ChangeCrossSection(app)
            Clearfn(app);

            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Width (in)';
                    app.EditField_2Label.Text='Thickness (in)';  
                else
                    app.EditField_1Label.Text='Width (m)';
                    app.EditField_2Label.Text='Thickness (m)';  
                end    
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';               
            end
            if(contains(n,'Pipe'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Outer Diameter (in)';
                    app.EditField_2Label.Text='Wall Thickness (in)';
                else
                    app.EditField_1Label.Text='Outer Diameter (m)';
                    app.EditField_2Label.Text='Wall Thickness (m)';
                end
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 if(app.iu==1)
                    app.EditField_1Label.Text='Diameter (in)';
                 else
                    app.EditField_1Label.Text='Diameter (m)';                     
                 end
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Base Width (in)';
                    app.EditField_2Label.Text='Total Height (in)';
                    app.EditField_3Label.Text='Flange Thickness (in)';     
                    app.EditField_4Label.Text='Web Thickness (in)';
                else
                    app.EditField_1Label.Text='Base Width (m)';
                    app.EditField_2Label.Text='Total Height (m)';
                    app.EditField_3Label.Text='Flange Thickness (m)';     
                    app.EditField_4Label.Text='Web Thickness (m)';
                end

                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Area (in^2)';
                    app.EditField_2Label.Text='Area moment Inertia (in^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                else
                    app.EditField_1Label.Text='Area (m^2)';
                    app.EditField_2Label.Text='Area moment Inertia (m^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (m)';
                end

                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end
        
        function ChangeMaterials(app)
            
            Clearfn(app);
            
            app.E=0;
            app.rho=0;            

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';   
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (in)';
                app.PointMasslbmEditFieldLabel.Text='Point Mass (lbm)';
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (m)';
                app.PointMasslbmEditFieldLabel.Text='Point Mass (kg)';         
            end    

            app.ElasticModuluspsiEditField.Value=sprintf('%8.4g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%8.4g',app.rho);        

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function beam_FEA_base_arbitrary(app)

            % disp('Arb')

            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    

            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end            

            app.fig_num=1;

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not FEA_base_arbitraryexist.  Try reading in external file.')
                     return;
                 end
            end 

            disp(' ');
            disp(' * * * * * ');
            disp(' ');

            FEA_base_arbitrary(app)

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function pre_common(app)
          

            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    
       

            app.fig_num=1;

            app.num_nodes_Tz_free=round(app.dof/2);
            
            % app.dm

            app.num_Tz=length(app.TZ_tracking_array);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%  need to cleanup
            
            sz=size(app.Mww);
            app.nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.omegan=2*pi*app.fn;

            app.omn2=app.omegan.^2;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            app.omega=2*pi*app.f;

            num_elem=app.ne;
                
            app.num_nodes=num_elem+1;
            %       total_dof=num_nodes*2;
                
                    app.R_tracking_array=zeros(app.num_nodes,1);
            
            %        rot_dof;  
                   
                
                    app.rot_constraint_matrix=app.constraint_matrix;
                
            %        constraint_matrix;
                
                    length(app.constraint_matrix(:,1))
                
                    for i=length(app.constraint_matrix(:,1)):-1:1
                        if(app.constraint_matrix(i,3)==0)
                            app.rot_constraint_matrix(i,:)=[];
                        end
                    end
                
                    nu=length(app.TT(:,1));
                    bbb=(1:nu);
                
                    for i=1:app.num_Tz
                        [Lia,Locb] =ismember(app.TZ_tracking_array,bbb);
                        if(Lia==1)
                            bbb(Locb)=[];
                        end
                    end
                
            %%        fprintf(' num_nodes=%d  num_Ur=%d \n',num_nodes,num_Ur);
                    ccc=app.rot_constraint_matrix(:,1);
                    
                    kv=1;
                    for jj=1:app.num_nodes    
                        if(ismember(jj,ccc))
            %                fprintf(' jj=%d  included \n',jj);
                        else    
            %                fprintf(' jj=%d  kv=%d \n',jj,kv);
                            app.R_tracking_array(jj)=bbb(kv);
                            kv=kv+1;
                        end
                    end
                
        end

        function pre_trans(app)  

            app.acc_trans=[];
            app.pv_trans=[];
            app.rd_trans=[];
            app.stress_trans=[]; 

            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end     

            app.node_near=str2num(app.ResponseNodeEditField.Value);

            app.omega=app.tpi*app.f;

            om2=app.omega.^2;
            
            %
            [~,jnear]=min(abs(app.omegan(1)-app.omega));
            
            N=zeros(app.nff,1);

            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            num_modes_include=str2num(app.NumberofModestoIncludeListBox.Value);

            fprintf('num_modes_include=%d\n',num_modes_include);
            
            iaa=1;
            ibb=num_modes_include;
                  
            if(app.nff> num_modes_include)
                app.nff=num_modes_include;
            end    
            
            %
            app.y=ones(app.nem,1);
            
            try
                A=-app.MST*app.Mwd*app.y;
            catch
                disp('app.nem');
                app.nem
                disp('app.MST')
                size(app.MST)
                disp('app.Mwd')
                size(app.Mwd)
                disp('app.y')
                size(app.y)
                warndlg('Error:  ref 1')
                return;
            end
            %
            app.acc_trans=zeros(app.np,app.num_Tz);
            app.pv_trans=zeros(app.np,app.num_Tz);
            app.rd_trans=zeros(app.np,app.num_Tz);
            app.stress_trans=zeros(app.np,app.num_Tz);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             damp=app.dm;

            app.np=length(app.f);

            progressbar;


            iaa=1;
            for k=1:app.np  % for each excitation frequency

                progressbar(k/app.np);
                

                N(iaa:ibb) = A(iaa:ibb) ./ (app.omn2(iaa:ibb) - om2(k) + 1i * 2 * damp(iaa:ibb) .* app.omegan(iaa:ibb) * app.omega(k));
  
                Ud=zeros(app.nem,1); 
                for i=1:app.nem  % convert acceleration input to displacement
                    Ud(i)=1/(-om2(k));
                end
            %
 

            %    Uw=app.ModeShapes(:,iaa:ibb)*N;
            % 


                MM=app.ModeShapes(:,1:ibb)*N(1:ibb,1);

              
                try
            
                    Udw=[Ud; MM];
                catch
                    disp('ref 30')
                    return;
                end
            %
                U=app.TT*Udw;    
                
                nu=length(U);
                
                if(k==1)
            %        fprintf(' nu=%d num_elem=%d  num_nodes_Tz_free=%d\n',nu,num_elem,num_nodes_Tz_free);
            %        ngw
                end
                
                Ur=zeros(nu,1);
                
                Ur(app.ngw(1:nu)) = U(1:nu);

 
                for jk=1:app.num_Tz
                    app.node=jk;    
                    ij=app.TZ_tracking_array(app.node);
                    app.acc_trans(k,jk)=om2(k)*abs(Ur(ij));
                    app.rd_trans(k,jk)=abs(Ur(ij)-Ud(1));
                end

                for nk=1:app.num_nodes-1
                
                    left_node=nk;
                    right_node=nk+1;
            
                    LL=abs(app.xx(left_node)-app.xx(right_node));
                    
                    [B]=beam_stress_B(0,LL);
                    
                    TL=app.TZ_tracking_array(left_node);
                    TR=app.TZ_tracking_array(right_node);
                    
                    URL=0;
                    URR=0;
                    
                    if(app.R_tracking_array(left_node)~=0)
                        RL=app.R_tracking_array(left_node);
                        URL=Ur(RL);
                    end
                    if(app.R_tracking_array(right_node)~=0)
                        RR=app.R_tracking_array(right_node);
                        URR=Ur(RR);            
                    end
                     
                     d=transpose([Ur(TL) URL Ur(TR) URR]);
            
            %       if(k==1)
            %           fprintf('nk=%d  %d %d %d %d   \n',nk,TL,RL,TR,RR);
            %            fprintf('  %8.4g  %8.4g  %8.4g  %8.4g \n\n',d(1),d(2),d(3),d(4));
            %            B
            %        end
            
                        app.stress_trans(k,nk)=abs(app.cna*app.E*B*d); 
                        
                       QQQ=1; 
                       if(k==jnear  && QQQ==1)
            %                 dzL=Ur(TL);
            %                 ryL=URL; 
            %                 dzR=Ur(TR); 
            %                 ryR=URR; 
                             app.s1=app.stress_trans(k,nk);
            %                 fprintf('\n\n nk=%d \n\n',nk);
            %                 fprintf(' cna=%g  E=%7.3g  s1=%8.4g \n',cna,E,s1*386);
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(1)*dzL),imag(B(1)*dzL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(2)*ryL),imag(B(2)*ryL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(3)*dzR),imag(B(3)*dzR));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(4)*ryR),imag(B(4)*ryR));   
            %                 cna*E*B*d
            %                transpose([Ur(TL) URL Ur(TR) URR]);
                        end
                    
                end
                
            % stress at right end  
                app.stress_trans(k,end)=app.stress_trans(k,end-1);
                
            end

            try
                app.pv_trans = app.rd_trans .* (2 * pi * app.f);
            catch
                
                disp('app.rd_trans')
                size(app.rd_trans)
                disp('app.f')
                size(app.f)
                warndlg('Error: ref 2');
                return;
            end

            md=5;
            x_label='Frequency (Hz)';

            %%%%%%%%%%%%%%%%

            app.freq=app.f;

            [~,column_a,app.max_a]=find_max_2d_array(app.acc_trans);
            

            app.pv_trans=app.scale*app.pv_trans;
            [~,column_pv,app.max_pv]=find_max_2d_array(app.pv_trans);
            

            app.rd_trans=app.scale*app.rd_trans;
            [~,column_rd,app.max_rd]=find_max_2d_array(app.rd_trans);
            

            app.stress_trans=app.scale*app.stress_trans;
            [~,app.column_st,app.max_st]=find_max_2d_array(app.stress_trans);
            

            %%%%%%%%%%%%%%%%%


            fprintf('\n Max Values \n');

            fprintf('          Acceleration Trans = %8.4g G/G \n',app.max_a);

            if(app.iu==1)
                fprintf('       Pseudo Velocity Trans = %8.4g ips/G \n',app.max_pv);
                fprintf(' Relative Displacement Trans = %8.4g in/G \n',app.max_rd);
                fprintf('                Stress Trans = %8.4g psi/G \n',app.max_st);  
            else
                fprintf('       Pseudo Velocity Trans = %8.4g m/sec/G \n',app.max_pv);
                fprintf(' Relative Displacement Trans = %8.4g m/G \n',app.max_rd);
                fprintf('                Stress Trans = %8.4g Pa/G \n',app.max_st); 
            end

            K=app.max_st/(app.max_pv*sqrt(app.E*app.rho));  

            if(app.itype==1)
                fprintf('\n Stress-Velocity K = %8.4g \n',K);      
            end

            if(app.itrans==1)

                app.ppp_a=[app.freq app.acc_trans(:,column_a)];
                app.ppp_pv=[app.freq app.pv_trans(:,column_pv)];
                app.ppp_rd=[app.freq app.rd_trans(:,column_rd)];
                app.ppp_st=[app.freq app.stress_trans(:,app.column_st)];

                if(app.chplot>=2 && app.itype==1)
    
                    %%%%%%%%%%%%%%%%%
        
                    y_label='Trans (G/G)';
                    t_string=sprintf('Acceleration Transmissibility at Node %d',app.node_near);
        
                    app.qqq_a=[app.freq app.acc_trans(:,app.node_near)];
    
                    if(min(app.qqq_a(:,2))>0)   
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_a,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
        
                    if(app.iu==1)
                        y_label='Trans (ips/G)';
                    else
                        y_label='Trans (m/sec/G)';                    
                    end    
    
                    t_string=sprintf('Pseudo Velocity Transmissibility at Node %d',app.node_near);
        
                    app.qqq_pv=[app.freq app.pv_trans(:,app.node_near)];
    
                    if(min(app.qqq_pv(:,2))>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_pv,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
        
                    if(app.iu==1)
                        y_label='Trans (in/G)';
                    else
                        y_label='Trans (m/G)';                    
                    end   
    
                    t_string=sprintf('Rel Disp Transmissibility at Node %d',app.node_near);
        
                    app.qqq_rd=[app.freq app.rd_trans(:,app.node_near)];
    
                    if(app.qqq_rd(:,2)>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_rd,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
        
                    if(app.iu==1)
                        y_label='Trans (psi/G)';
                    else
                        y_label='Trans (Pa/G)';                    
                    end   
    
                    t_string=sprintf('Stress Transmissibility at Node %d',app.node_near);
        
                    app.qqq_st=[app.freq app.stress_trans(:,app.node_near)];
    
                    if(app.qqq_st(:,2)>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_st,app.fmin,app.fmax,md);            
                    end
    
                end    
    
                if(app.chplot~=2 && app.itype==1)
                
                    %%%%%%%%%%%%%%%%%
        
                    y_label='Trans (G/G)';
                    t_string=sprintf('Maximum Acceleration Transmissibility at x=%7.3g %s',app.xx(column_a),app.LU);
        
                    if(min(app.ppp_a(:,2))>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_a,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
        
                    if(app.iu==1)
                        y_label='Trans (ips/G)';
                    else
                        y_label='Trans (m/sec/G)';                    
                    end   
    
                    t_string=sprintf('Maximum Pseudo Velocity Transmissibility at x=%7.3g %s',app.xx(column_pv),app.LU);
        
                    if(min(app.ppp_pv(:,2))>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_pv,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
        
                    if(app.iu==1)
                        y_label='Trans (in/G)';
                    else
                        y_label='Trans (m/G)';                    
                    end   
    
    
                    t_string=sprintf('Max Rel Disp Transmissibility at x=%7.3g %s',app.xx(column_rd),app.LU);
        
                    if(app.ppp_rd(:,2)>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_rd,app.fmin,app.fmax,md); 
                    end
        
                    %%%%%%%%%%%%%%%%%
                    
                    if(app.iu==1)
                        y_label='Trans (psi/G)';
                    else
                        y_label='Trans (Pa/G)';                    
                    end   
    
    
                    t_string=sprintf('Max Stress Transmissibility at x=%7.3g %s',app.xx(app.column_st),app.LU);
        
                    if(app.ppp_st(:,2)>0)
                        [app.fig_num,~]=...
                            plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_st,app.fmin,app.fmax,md);            
                    end
    
                end
    
                %%%%%%%%%%%%%%%%%         

                prefix=app.OutputArrayPrefixEditField.Value;

                ss1=sprintf('%s_accel',prefix);
                ss2=sprintf('%s_pv',prefix);
                ss3=sprintf('%s_rd',prefix);
                ss4=sprintf('%s_stress',prefix);
    
                assignin('base', ss1, app.ppp_a);
                assignin('base', ss2, app.ppp_pv);
                assignin('base', ss3, app.ppp_rd);
                assignin('base', ss4, app.ppp_st);

                ss=sprintf('Output arrays \n\n %s \n %s \n %s \n %s',ss1,ss2,ss3,ss4);

                app.TransOutputArraysReadOnlyTextArea.Value=ss;
    
           end

       end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.fig_num=1;

            app.tpi=2*pi;

            app.oct96=2^(1/96);
            app.oct48=2^(1/48);

            app.RainflowFatigueButton.Enable='off';

            app.itrans=0;

            Clearfn(app);
            ClearResults(app);
            PointMass(app);
            ChangeUnits(app);
            CloseFigures(app);
            ChangeCrossSection(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            
            ChoosePlots(app);
            update_psd(app);
            update_srs(app);
            update_th(app);

            app.PlotListBox.Visible='on';
            app.PlotListBoxLabel.Visible='on';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_fn,s) % adjust the style
            addStyle(app.UITable,s) % adjust the style
            addStyle(app.UITable_nodes,s);

        end

        % Button pushed function: CalculateNaturalFrequenciesButton
        function CalculateNaturalFrequenciesButtonPushed(app, event)
            
            app.dm=[];
            app.fn=[];
            app.freq=[];
            app.omegan=[];
            app.omega=[];
            app.ModeShapes=[];
            app.tracking_array=[];
            app.free_dof_array=[];
                
            
            CloseFigures(app);
            app.UITable_fn.Data='';

      
            fprintf('\n * * * * * * * * * \n\n');  

            app.L=str2num(app.LengthinEditField.Value);
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);

            app.FullModeShapes=[];
            app.TotalModeShapes=[];
       
            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
            end
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=3;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=1;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=3;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=1;                
            end    
            
    
            %%%%%%%%%%%%%%%%%%%%%

            app.L
            app.E
            app.rho
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=str2num(app.EditField_1.Value);
                thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=str2num(app.EditField_1.Value);
                wall_thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=str2num(app.EditField_1.Value);
                [area,app.MOI,app.cna]=cylinder_geometry(diameter); 

                diameter
                area
                app.MOI
                app.cna

            end
            if(contains(n,'I-Beam'))
                B=str2num(app.EditField_1.Value);
                H=str2num(app.EditField_2.Value);
                Tf=str2num(app.EditField_3.Value);     
                Tw=str2num(app.EditField_4.Value);  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=str2num(app.EditField_1.Value);
                app.MOI=str2num(app.EditField_2.Value);
                app.cna=str2num(app.EditField_3.Value);       
            end
            
            nsm=str2num(app.UniformNonstructuralMasslbmEditField.Value);

            if(app.iu==1)
                nsm=nsm/386;
            end
            
            rho_mpL=app.rho*area + nsm/app.L;   % mass per unit length
            tmass=rho_mpL*app.L;
            app.sq_mass=sqrt(tmass);

            fprintf('\n area=%8.4g in^2  \t mass=%8.4g lbf sec^2/in \t MOI=%8.4g in^4 \n\n',area,tmass,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%

            try
            
                app.ne=str2num(app.NumberofElementsEditField.Value);
    
                if(app.ne==0 || isempty(app.ne))
                    warndlg(' Number of elements must be >=1 '); 
                    return; 
                end

            catch
                warndlg('Enter the number of elements');
                return;
            end

            app.num_nodes=2*app.ne;

            deltax=app.L/app.ne;
    
            app.xx=zeros((app.ne+1),1);

            for i=2:(app.ne+1)
                app.xx(i)=app.xx(i-1)+deltax; 
            end 

            numi=0;
            N=numi;


            if(N==0)

                int_index=0;

            else     

                int_index=zeros(N,1);            

                % datad=app.UITable_msweep.Data;

                datad=[];

                A=string(datad);
     
                int_loc=zeros(N,1);
                
                try
                    k=1;
    
                    for i=1:N
                        k=k+1;
                    end    

                    for i=1:N
         
                        a=str2num(A(k)); k=k+1;

                        int_loc(i,1) = a;
        
                        if(int_loc(i)>=app.L)
                            warndlg('Intermediate location >= length ');
                            return;
                        end

                        [~,index] = min(abs(app.xx-int_loc(i)));
        
                        app.xx(index)=int_loc(i);
        
                        int_index(i)=index;

                    end  

                    int_index=sort(int_index);
                    
                catch
                    warndlg('Input Arrays read failed');
                    return;
                end

            end
            
            app.dx=zeros(app.ne,1);
            for i=1:app.ne
                app.dx(i)=app.xx(i+1)-app.xx(i);
                
                if(app.dx(i)<1.0e-12)
                    warndlg('Coincident nodes. Increase number of elements');
                    return;
                end
                
            end
            
            disp(' ');
            disp(' Node locations');
            for i=1:length(app.xx)
                fprintf(' %8.4g \n',app.xx(i));
                dd{i,1}=app.xx(i);
                ss{i}=sprintf('%d',i);
            end
            disp(' ');

            app.UITable_nodes.Data=dd;
             app.UITable_nodes.RowName=ss;

            app.dof=2*app.ne+2;

            I=app.MOI;

            [klocal] = local_stiffness(app.E,I,app.ne,app.dx);
            [mlocal] = local_mass(rho_mpL,app.ne,app.dx);
            
            % app.kk=zeros(4,4);
            % mm=zeros(4,4);
            
            % for i=1:4
            %    for j=1:4
            %        app.kk(i,j)=klocal(1,i,j);
            %        mm(i,j)=mlocal(1,i,j);
            %    end
            % end  

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            npm=str2num(app.NumberofPointMassesListBox.Value);
            
            pmloc=zeros(1,1);
            pm=zeros(1,1);
            
            if(npm==1)
                   pm(1)=str2num(app.PointMasslbmEditField.Value);
                pmloc(1)=str2num(app.PointMassLocationfromLeftEndinEditField.Value);
            end
            
            if(app.iu==1) % English
                pm=pm/386;
            end  

            tmass=tmass+pm;

            app.tracking_array=zeros(app.dof,1);
            
            for i=1:app.dof
                app.tracking_array(i)=i;
                app.free_dof_array(i)=i;
            end
            
            app.TZ_tracking_array=zeros(app.dof,2);
            
            app.num_nodes=round(app.dof/2);
            
            for i=1:app.num_nodes
               app.TZ_tracking_array(i)=2*i-1;
            end


            [app.stiff,app.mass,app.stiff_unc,app.mass_unc,app.dof,app.dof_status,ivector]=...
                       mspan_beam_assembly_unc_pm(app.ne,klocal,mlocal,app.LBC,app.RBC,app.dof,numi,int_index,npm,pmloc,pm,app.xx);           
            
            %%%%%%

            %% disp('dof_status')
            %% for i=1:length(dof_status)
            %%    fprintf(' %g \n',dof_status(i));
            %% end
            %% disp(' ');
            
            %% disp('ivector');
            %% for i=1:length(ivector)
            %%    fprintf(' %g \n',ivector(i));
            %% end
            %% disp(' ');

            if(app.ne==1)
                app.stiff
                app.mass
            end
           
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            total_mass=tmass;
        
            if(app.iu==1) % English    
                out1=sprintf('\n Total mass = %8.4g lbm ',tmass*386);
            else
                out1=sprintf('\n Total mass = %8.4g kg ',tmass);        
            end
            
            disp(out1);   

            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiff,app.mass,2);

            sz=size(app.ModeShapes);

            orig_dof=length(app.dof_status);

            app.TotalModeShapes=zeros(orig_dof,length(app.fn));

            k=sz(1);

            for i=orig_dof:-1:1
                if(app.dof_status(i)==1)
                    app.TotalModeShapes(i,:)=app.ModeShapes(k,:);
                    k=k-1;
                end
            end    

            disp('ref nm1 ')
            size(app.TotalModeShapes)


            [app.part]=beam_fn_pf_emm(app.iu,total_mass,app.dof,app.fn,app.mass,app.ModeShapes,app.MST,app.dof_status,ivector);  % function

            app.fn=fix_size(app.fn);
     
            n=length(app.fn);

            if(n>12)
                n=12;
            end    

            emm=zeros(n,1);
            cp=zeros(n,1);

            rho_mpa=(rho_mpL*app.L)/area;

            EI_term = sqrt(app.E*app.MOI/rho_mpa);

            for i=1:n
                app.beta(i)=sqrt(app.omegan(i)/EI_term);
                cp(i)=app.beta(i)*EI_term;
                kw=app.omegan(i)/cp(i);
                emm(i)=app.part(i)^2/tmass;
    
                data_s{i,1}=sprintf('%d',i);
                data_s{i,2}=sprintf('%8.4g',app.fn(i));
                data_s{i,3}=sprintf('%7.3g',app.part(i));
                data_s{i,4}=sprintf('%7.3f',emm(i));
                data_s{i,5}=sprintf('%7.4g',cp(i));
                data_s{i,6}=sprintf('%7.3g',kw);
            end

            app.UITable_fn.Data=data_s;
            app.UITable_fn.Visible='on';

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         
            nnum=length(app.fn);

            dof_half=round(orig_dof/2);
            %
   
            for j=1:nnum
            %     
                  pf_mode=app.ModeShapes(:,j);  
            %
                    for i=1:orig_dof
                        if(app.dof_status(i)==0)
            %       
                            pf_mode=[pf_mode(1:(i-1));0;pf_mode(i:length(pf_mode))];
            %
                        end
                    end
            %
                    pmode=zeros(dof_half,1);
            %        
                    for i=1:dof_half
                        pmode(i)=pf_mode(2*i-1);
                    end
            %
                    app.FullModeShapes(:,j)=pmode;

            end

            app.ModeShapes_corrected=app.FullModeShapes;

            sz=size(app.FullModeShapes);

            Y=app.FullModeShapes;
            Yc=conj(Y);

            nn=min([8 sz(2)]);

            mac=zeros(nn,nn);

            for i=1:nn
                for j=1:nn
                    a=Yc(:,i)'*Y(:,j);
                    a=abs(a)^2;
                    b=Yc(:,i)'*Y(:,i)*Yc(:,j)'*Y(:,j);
                    mac(i,j)=a/b;
                end
            end

            data=mac;

            figure(99)
            h=bar3(data);
            title('Modal Assurance Criterion');
            colorbar
            xlabel('mode');
            ylabel('mode');
            zlim([0 1]);

            view(-100,56);

            for i = 1:size(data,2)
                cdata = get(h(i),'cdata');
                k = 1;
                for j = 0:6:(6*size(data,1)-6)
                    cdata(j+1:j+6,:) = data(k,i);
                    k = k+1;
                end
                set(h(i),'cdata',cdata);
            end
            
            colormap(jet);
            colorbar;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Partition(app);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.PlotModeShapeButton.Enable='on';
            app.PlotModeShapeButton.Visible='on';
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
 
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            Clearfn(app);
            ChangeMaterials(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            Clearfn(app);
            ChangeCrossSection(app);
        end

        % Callback function
        function NumberListBoxValueChanged(app, event)
           Clearfn(app); 
          
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
               
            n=12;

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else

                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;           
                    for i=1:length(vd)
                        v=vd{i};
                        app.dm(i)=v;
                    end            
                end   
            end


            msgbox('Damping saved');
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            nnum=str2num(app.SelectModeforPlotListBox.Value);
              
            LF=length(app.fn);
            
            if(nnum>LF)
                out1=sprintf(' Highest mode number = %d',LF);
                warndlg(out1);
                return;
            end
               
            orig_dof=length(app.dof_status);
            dof_half=round(orig_dof/2);
            %
            choice=1;
            %
            for j=nnum:nnum
            %
                if(choice==1)
            %      
                  at = sprintf(' Mode %d   freq=%8.4g Hz',j,app.fn(j));
                  pf_mode=app.ModeShapes(:,j);  
            %
                    for i=1:orig_dof
                        if(app.dof_status(i)==0)
            %       
                            pf_mode=[pf_mode(1:(i-1));0;pf_mode(i:length(pf_mode))];
            %
                        end
                    end
            %
                    pmode=zeros(dof_half,1);
            %        
                    for i=1:dof_half
                        pmode(i)=pf_mode(2*i-1);
                    end
            %
                    figure(app.fig_num);
                    app.fig_num=app.fig_num+1;
            %
                    plot(app.xx,pmode); 
                    grid;
                    set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');    
                    title(at);
                    if(app.iu==1)
                        xlabel('x (inch)'); 
                    else
                        xlabel('x (meters)');
                    end    
                    ylabel('Mass Normalized Modal Displacement');
            %
                    if(nnum==1)
                        assignin('base','mode1', [app.xx pmode]);
                    end
                end
            end

        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            Clearfn(app);
            app.R_tracking_array=[];
            app.rot_constraint_matrix=[];
            app.constraint_matrix=[];

        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
         
            Clearfn(app);
            app.R_tracking_array=[];
            app.rot_constraint_matrix=[];
            app.constraint_matrix=[];         
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
           
            Clearfn(app);
        end

        % Value changed function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: UniformNonstructuralMasslbmEditField
        function UniformNonstructuralMasslbmEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Callback function
        function UITable_msweepCellEdit(app, event)
  
            Clearfn(app);
        end

        % Callback function
        function UITable_msweepButtonDown(app, event)
            Clearfn(app);
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed(app, event)

            app.itrans=1; 
            app.itype=1;


            app.f=[];

            app.fmin=str2num(app.StartFreqHzEditField.Value);
            app.fmax=str2num(app.EndFreqHzEditField.Value);

            Items = strtrim(app.OutputFrequencySpacingListBox.Items);
            im = strtrim(app.OutputFrequencySpacingListBox.Value);
            ndf = find(strcmp(Items, im));

            if(app.fmin<=0.01 && ndf>=3)
                app.fmin=0.01;
            end    

            app.f=zeros(10000,1);

            app.f(1)=app.fmin;


            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3)     
                    app.f(k)=app.f(k-1)*app.oct96;
                end
                if(ndf==4)     
                    app.f(k)=app.f(k-1)*app.oct48;
                end
                
                if(app.f(k)>app.fmax)
                    app.f(k)=app.fmax;
                    break;
                end
                
                k=k+1;
            end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           

           app.acc_trans=[];
            app.pv_trans=[];
            app.rd_trans=[];
            app.stress_trans=[]; 


            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end     

            app.node_near=str2num(app.ResponseNodeEditField.Value);

            app.omega=app.tpi*app.f;

            om2=app.omega.^2;
            
            %
            [~,jnear]=min(abs(app.omegan(1)-app.omega));


            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            num_modes_include=str2num(app.NumberofModestoIncludeListBox.Value);

            fprintf('num_modes_include=%d\n',num_modes_include);


            iaa=1;
            ibb=num_modes_include;
                  
            if(app.nff> num_modes_include)
                app.nff=num_modes_include;
            end    

            app.nff


            %
            app.y=ones(app.nem,1);
            
            try
                A=-app.MST*app.Mwd*app.y;
            catch
                disp('app.nem');
                app.nem
                disp('app.MST')
                size(app.MST)
                disp('app.Mwd')
                size(app.Mwd)
                disp('app.y')
                size(app.y)
                warndlg('Error:  ref 1')
                return;
            end
            %
            app.acc_trans=zeros(app.np,app.num_Tz);
            app.pv_trans=zeros(app.np,app.num_Tz);
            app.rd_trans=zeros(app.np,app.num_Tz);
            app.stress_trans=zeros(app.np,app.num_Tz);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            pre_trans(app);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            pref=app.OutputArrayPrefixEditField.Value;

            if(app.chplot~=2)
                a=sprintf('%s_accel_max',pref);
                app.pv=sprintf('%s_pv_max',pref);
                app.rd=sprintf('%s_rel_disp_max',pref);
                st=sprintf('%s_stress_max',pref);

                assignin('base',a, app.ppp_a);
                assignin('base',app.pv, app.ppp_pv);
                assignin('base',app.rd, app.ppp_rd);
                assignin('base',st, app.ppp_st);
            end   

            if(app.chplot>=2)
                a=sprintf('%s_accel_node_%d',pref,app.node_near);
                app.pv=sprintf('%s_pv_node_%d',pref,app.node_near);
                app.rd=sprintf('%s_rel_disp_node_%d',pref,app.node_near);
                st=sprintf('%s_stress_node_%d',pref,app.node_near);

                assignin('base',a, app.qqq_a);
                assignin('base',app.pv, app.qqq_pv);
                assignin('base',app.rd, app.qqq_rd);
                assignin('base',st, app.qqq_st);            
            end               

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            CloseFigures(app);

            app.itype=2;

            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 


            app.fmin=app.THM(1,1);
            app.fmax=app.THM(end,1);
            
            Items = strtrim(app.OutputFrequencySpacingListBox_psd.Items);
            im = strtrim(app.OutputFrequencySpacingListBox_psd.Value);
            ndf = find(strcmp(Items, im));


            app.f=zeros(10000,1);

            app.f(1)=app.fmin;
            
            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3)     
                    app.f(k)=app.f(k-1)*app.oct96;
                end
                if(ndf==4)     
                    app.f(k)=app.f(k-1)*app.oct48;
                end

                if(app.f(k)>app.fmax)
                    app.f(k)=app.fmax;
                    break;
                end
                
                k=k+1;
            end
            
            app.f=app.f(1:k);

            app.np=length(app.f); 

            [base_psd]=interp_psd_oct(app.THM(:,1),app.THM(:,2),app.f);

            pre_trans(app);

            app.accel_psd = base_psd .* app.acc_trans.^2;
            app.pv_psd = base_psd .* app.pv_trans.^2;
            app.rd_psd = base_psd .* app.rd_trans.^2;
            app.stress_psd = base_psd .* app.stress_trans.^2;
     
            
            fprintf('\n  np=%d  ne=%d \n',app.np,app.ne);

            app.omega=2*pi*app.f;

             % app.stress_psd=app.stress_psd*app.scale^2;  already has
             % scale

             if(app.iu==2)
                 app.rd_psd=app.rd_psd*1000^2;
             end    

            nn=app.ne+1;

            accel_rms=zeros(nn,1);
            pv_rms=zeros(nn,1);
            rd_rms=zeros(nn,1);
            stress_rms=zeros(nn,1);

            [~,base_grms]=calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2));

            for j=1:nn
                max(app.accel_psd(:,j))
                [~,accel_rms(j)]=calculate_PSD_slopes_no(app.f,app.accel_psd(:,j)  );            
            end

            for j=1:nn
                [~,pv_rms(j)]=calculate_PSD_slopes_no(app.f,app.pv_psd(:,j)  );
            end  

            for j=1:nn 
                [~,rd_rms(j)]=calculate_PSD_slopes_no(app.f,app.rd_psd(:,j)  );         
            end    

            for j=1:nn 
                [~,stress_rms(j)]=calculate_PSD_slopes_no(app.f,app.stress_psd(:,j)  );           
            end           


            md=5;
            x_label='Frequency (Hz)';
    
            [Ca,Ia]=max(accel_rms);
            [Cpv,Ipv]=max(pv_rms);            
            [Crd,Ird]=max(rd_rms);
            [Cst,Ist]=max(stress_rms);

            output_name=app.OutputPSDArrayPrefixEditField.Value;

            fprintf('\n  Ia=%d  Ipv=%d  Ird=%d  Ist=%d \n',Ia,Ipv,Ird,Ist);

                t_string=sprintf('Max Accel PSD  at node %d',app.node);
                y_label='Accel (G^2/Hz)';

                ppp1=[app.f app.accel_psd(:,Ia)];
                ppp2=app.THM;

                name1=sprintf('%s_accel_max_node%d',output_name,app.node);
                assignin('base',name1, ppp1);
                ss=name1;

                leg1=sprintf('Response  %7.3g GRMS',Ca);
                leg2=sprintf('Base Input  %7.3g GRMS',base_grms);

                [app.fig_num,h2]=plot_loglog_function_md_two_h2(app.fig_num,x_label,...
                    y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);

                %%

                pv_psd_max=[app.f app.pv_psd(:,Ipv)];

                if(app.iu==1)
                    t_string=sprintf('Max Pseudo Velocity PSD \n %7.3g in/sec RMS at node %d',Cpv,Ipv);
                    y_label='Velocity [(in/sec)^2/Hz]';
                else
                    t_string=sprintf('Max Pseudo Velocity PSD \n %7.3g m/sec RMS at node %d',Cpv,Ipv);
                    y_label='Velocity [(m/sec)^2/Hz]';
                end    

                name1=sprintf('%s_pv_max_node%d',output_name,Ipv);
                assignin('base',name1, pv_psd_max);
                ss=sprintf('%s\n%s',ss,name1);

                [app.fig_num,~]=...
                    plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,pv_psd_max,app.fmin,app.fmax,md); 

                %%

                size(app.rd_psd)

                rd_psd_max=[app.f app.rd_psd(:,Ird)];

                if(app.iu==1)
                    t_string=sprintf('Max Relative Displacement PSD \n %7.3g in RMS at node %d',Crd,Ird);
                    y_label='Rel Disp (in^2/Hz)';
                else
                    t_string=sprintf('Max Relative Displacement PSD \n %7.3g m RMS at node %d',Crd,Ird);
                    y_label='Rel Disp (m^2/Hz)';
                end    

                name1=sprintf('%s_rd_max_node%d',output_name,Ird);
                assignin('base',name1, rd_psd_max);
                ss=sprintf('%s\n%s',ss,name1);

                [app.fig_num,~]=...
                    plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,rd_psd_max,app.fmin,app.fmax,md); 


                stress_psd_max=[app.f app.stress_psd(:,Ist)];

                name1=sprintf('%s_stress_max_node%d',output_name,Ist);
                assignin('base',name1, stress_psd_max);
                ss=sprintf('%s\n%s',ss,name1);

                if(app.iu==1)
                    t_string=sprintf('Max Stress PSD \n %7.3g psi RMS at element %d',Cst,Ist);
                    y_label='Stress (psi^2/Hz)';
                else
                    t_string=sprintf('Max Stress PSD \n %7.3g Pa RMS at element %d',Cst,Ist);
                    y_label='Velocity (Pa^2/Hz)';
                end    

                if(min(stress_psd_max(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,stress_psd_max,app.fmin,app.fmax,md); 
                end

                app.PSDOutputArraysTextArea.Value=ss;

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output_stiff=app.StiffnessArrayEditField.Value;
            output_mass=app.MassArrayEditField.Value;
            output_modeshapes=app.ModeShapesEditField.Value;
            output_fn=app.NaturalFrequenciesEditField.Value;


            assignin('base',output_stiff, app.stiff);
            assignin('base',output_mass, app.mass);
            assignin('base',output_modeshapes, app.ModeShapes);
            assignin('base',output_fn, app.fn);

            msgbox('Arrays saved');
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)

            ChoosePlots(app);
        end

        % Button down function: TransmissibilityTab
        function TransmissibilityTabButtonDown(app, event)
            app.PlotListBox.Visible='on';
            app.PlotListBoxLabel.Visible='on';
            
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            ChangeUnits(app);

        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: SelectInputArrayListBox_psd
        function SelectInputArrayListBox_psdValueChanged(app, event)
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
            
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)

            try
                beam_bending_FEA.num_modes=app.NumberofModestoIncludeListBox.Value;
            catch
            end            
            try
                beam_bending_FEA.x=app.NodeNumberEditField.Value;
            catch
            end
            try
                beam_bending_FEA.L=app.LengthinEditField.Value;
            catch
            end            
            try
                beam_bending_FEA.E=app.ElasticModuluspsiEditField.Value;
            catch
            end
            try
                beam_bending_FEA.rho=app.MassDensitylbmin3EditField.Value;
            catch
            end
            try
                beam_bending_FEA.E1=app.EditField_1.Value;
            catch
            end
            try
                beam_bending_FEA.E2=app.EditField_2.Value;
            catch
            end
            try
                beam_bending_FEA.E3=app.EditField_3.Value;
            catch
            end
            try
                beam_bending_FEA.E4=app.EditField_4.Value;
            catch
            end
            try
                beam_bending_FEA.nsm=app.UniformNonstructuralMasslbmEditField.Value;
            catch
            end
            try
                beam_bending_FEA.ne=app.NumberofElementsEditField.Value;
            catch
            end
            try
                beam_bending_FEA.fmin=app.StartFreqHzEditField.Value;
            catch
            end
            try
                beam_bending_FEA.fmax=app.EndFreqHzEditField.Value;
            catch
            end    
            try
                beam_bending_FEA.nmn=app.DampingTypeListBox.Value;
            catch
            end
            try
                beam_bending_FEA.nD=app.ModalDistributionListBox.Value;
            catch
            end
            try
                beam_bending_FEA.cross=app.CrossSectionListBox.Value;
            catch
            end
            try
                beam_bending_FEA.plot=app.PlotListBox.Value;
            catch
            end
            try
                beam_bending_FEA.unit=app.UnitsListBox.Value;
            catch
            end
            try
                beam_bending_FEA.iu=app.iu;
            catch
            end    
            try
                beam_bending_FEA.mat=app.MaterialListBox.Value;
            catch
            end
            try
                beam_bending_FEA.nL=app.LeftBCListBox.Value;
            catch
            end
            try    
                beam_bending_FEA.nR=app.RightBCListBox.Value;
            catch
            end
            try        
                beam_bending_FEA.dd=app.UITable.Data;
            catch
            end
            try    
                beam_bending_FEA.npm=app.NumberofPointMassesListBox.Value;
            catch
            end
            try       
                beam_bending_FEA.pmloc=app.PointMassLocationfromLeftEndinEditField;
            catch
            end
            try      
                beam_bending_FEA.pm=app.PointMasslbmEditField.Value;
            catch
            end
            
            % % %
             
             structnames = fieldnames(beam_bending_FEA, '-full'); % fields in the struct
              
            % % %
             
                [writefname, writepname] = uiputfile('*.mat','Save data as');
               
                elk=sprintf('%s%s',writepname,writefname);
            
            try
                save(elk, 'beam_bending_FEA'); 
            catch
                warndlg('Save error');
                return;
            end
             
            disp(' ');
            out1=sprintf('Save Complete: %s',writefname);
            disp(out1);
            msgbox(out1);

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
            [filename, pathname] = uigetfile('*.mat');
             
            NAME = [pathname,filename];
             
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
             
            disp(' ref 2');
             
            k=length(structnames);
             
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
             
            disp(' ref 3');
             
            structnames
             
             
            % struct
             
            try
                beam_bending_FEA=evalin('base','beam_bending_FEA');
            catch
                warndlg(' evalin failed ');
                return;
            end


            try
                app.NumberofModestoIncludeListBox.Value=beam_bending_FEA.num_modes;
            catch
            end
            try                
                app.NodeNumberEditField.Value=beam_bending_FEA.x;
            catch
            end
            try       
                app.LengthinEditField.Value=beam_bending_FEA.L;
            catch
            end
            try
                app.ElasticModuluspsiEditField.Value=beam_bending_FEA.E;
            catch
            end
            try             
                app.MassDensitylbmin3EditField.Value=beam_bending_FEA.rho;
            catch
            end
            try             
                app.EditField_1.Value=beam_bending_FEA.E1;
            catch
            end
            try          
                app.EditField_2.Value=beam_bending_FEA.E2;
            catch
            end
            try           
                app.EditField_3.Value=beam_bending_FEA.E3;
            catch
            end
            try        
                app.EditField_4.Value=beam_bending_FEA.E4;
            catch
            end
            try
                app.UniformNonstructuralMasslbmEditField.Value=beam_bending_FEA.nsm;
            catch
            end
            try        
                app.NumberofElementsEditField.Value=beam_bending_FEA.ne;
            catch
            end
            try
                app.StartFreqHzEditField.Value=beam_bending_FEA.fmin;           
            catch
            end
            try     
                app.EndFreqHzEditField.Value=beam_bending_FEA.fmax;
            catch
            end
            try           
                app.DampingTypeListBox.Value=beam_bending_FEA.nmn;
            catch
            end
            try        
                app.ModalDistributionListBox.Value=beam_bending_FEA.nD;
            catch
            end
            try   
                app.CrossSectionListBox.Value=beam_bending_FEA.cross;
            catch
            end
            try
                app.PlotListBox.Value=beam_bending_FEA.plot;
            catch
            end
            try          
                app.UnitsListBox.Value=beam_bending_FEA.unit;
            catch
            end
            try           
                app.iu=beam_bending_FEA.iu;
            catch
            end
            try
                app.MaterialListBox.Value=beam_bending_FEA.mat;
            catch
            end
            try       
                app.LeftBCListBox.Value=beam_bending_FEA.nL;
            catch
            end
            try          
                app.RightBCListBox.Value=beam_bending_FEA.nR;  
            catch
            end
            try          
                app.UITable.Data=beam_bending_FEA.dd;
            catch
            end
            try
                app.NumberofPointMassesListBox.Value=beam_bending_FEA.npm;
            catch
            end
            try     
                app.PointMassLocationfromLeftEndinEditField=beam_bending_FEA.pmloc;
            catch
            end
            try        
                app.PointMasslbmEditField.Value=beam_bending_FEA.pm;
            catch
            end
          
            % app.UITable_msweep.Data=beam_bending_FEA.mdata;

            ChangeUnits(app);
            CloseFigures(app);
            ChangeCrossSection(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            ChoosePlots(app);
            update_psd(app);
            PointMass(app);
        end

        % Value changed function: NumberofPointMassesListBox
        function NumberofPointMassesListBoxValueChanged(app, event)
            
            PointMass(app);
        end

        % Button pushed function: CalculateBaseInputSine
        function CalculateBaseInputSineButtonPushed(app, event)
          
            app.itrans=0;

            app.titlea='Sine';

            app.itype=1;

            app.omegan=2*pi*app.fn;

            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    

            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end       

            app.f=str2num(app.ExcitationFreqHzEditField.Value);
            app.omega=2*pi*app.f;
            base=str2num(app.BaseAccelerationGEditField.Value);


            pre_trans(app);

            app.node_near

            n_a=base*app.acc_trans(:,app.node_near);
            n_pv=base*app.pv_trans(:,app.node_near);
            n_rd=base*app.rd_trans(:,app.node_near);
            % n_st=base*app.stress_trans(:,app.node_near);


            if(app.iu==1)
                ss1=sprintf(' Accel = %8.4g G \n',n_a);
                ss2=sprintf(' Pseudo Vel = %8.4g in/sec \n',n_pv);
                ss3=sprintf(' Rel Disp = %8.4g in \n',n_rd);
                % ss4=sprintf(' Stress = %8.4g psi \n',n_st);
            else
                ss1=sprintf(' Accel = %8.4g G \n',n_a);
                ss2=sprintf(' Pseudo Vel = %8.4g m/sec \n',n_pv);
                ss3=sprintf(' Rel Disp = %8.4g m \n',n_rd);
                % ss4=sprintf(' Stress = %8.4g Pa \n',n_st);
            end    
    
            sq=sprintf('Values at node %d \n\n %s %s %s  ',app.node,ss1,ss2,ss3);         

            %%%    

            app.rd_max=app.rd_max*app.scale;

            app.pv_max = app.rd_max .* (2 * pi * app.f);

            app.max_a=base*app.max_a;
            app.max_pv=base*app.max_pv;
            app.max_rd=base*app.max_rd;
            app.max_st=base*app.max_st;


            if(app.iu==1)
                ss1=sprintf(' Accel = %8.4g G \n',app.max_a);
                ss2=sprintf(' Pseudo Vel = %8.4g in/sec \n',app.max_pv);
                ss3=sprintf(' Rel Disp = %8.4g in \n',app.max_rd);
                ss4=sprintf(' Stress = %8.4g psi \n',app.max_st);
            else
                ss1=sprintf(' Accel = %8.4g G \n',app.max_a);
                ss2=sprintf(' Pseudo Vel = %8.4g m/sec \n',app.max_pv);
                ss3=sprintf(' Rel Disp = %8.4g m \n',app.max_rd);
                ss4=sprintf(' Stress = %8.4g Pa \n',app.max_st);
            end    

            ss=sprintf('%s \n\nMaximum Values \n\n %s %s %s %s ',sq,ss1,ss2,ss3,ss4);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';             

        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFreqHzEditField.Value=sprintf('%8.4g',app.fn(1));
            ClearResults(app);
        end

        % Value changing function: ExcitationFreqHzEditField
        function ExcitationFreqHzEditFieldValueChanging(app, event)
   
            ClearResults(app);
        end

        % Value changing function: BaseAccelerationGEditField
        function BaseAccelerationGEditFieldValueChanging(app, event)
 
            ClearResults(app);
        end

        % Value changed function: NumberofModestoIncludeListBox
        function NumberofModestoIncludeListBoxValueChanged(app, event)
   
            ClearResults(app);
        end

        % Button pushed function: CalculateBaseInputMDOFSRS
        function CalculateBaseInputMDOFSRSButtonPushed(app, event)
            
            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    

            try
                Items = char(app.listbox_method_srs.Items);
                im=app.listbox_method_srs.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end            

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_srs.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            num_srs=length(app.THM(:,1));
            
            
            disp(' ');
            disp(' * * * * * * * * * * ');
            disp(' ');
            
            app.fig_num=1;
           
            ChoosePlots(app);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
    
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            app.fmin=app.THM(1,1);
            app.fmax=app.THM(end,1);
                             
            [app.fig_num]=plot_loglog_function_md(app.fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);                     

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fnn=app.fn;

            if(app.fn(1)<app.THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            while(fnn(end)>app.THM(end,1))
                fnn(end)=[];
            end 

            num_fn=length(fnn);

            n2=str2num(app.NumberofModestoIncludeListBox.Value);

            num_fn=min([num_fn n2]);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                          
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);

            fprintf('\n num_fn=%d  num_srs=%d\n',num_fn,num_srs);
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            % app.xx=[0. 0.5 1]*app.L;
            nxx=1;

            
            accel_abssum=zeros(nxx,1);
              accel_srss=zeros(nxx,1);
              
            pv_abssum=zeros(nxx,1);
              pv_srss=zeros(nxx,1);  
            
            rd_abssum=zeros(nxx,1);
              rd_srss=zeros(nxx,1);
            
            for i=1:nxx

                for j=1:num_fn
                    
                    PFM=app.part(j)*max(abs(app.FullModeShapes(:,j)));
                    % fprintf(' i=%d  j=%d  PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,PFM,j,a_srs(j));
                    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            ss1=sprintf('Acceleration (G)');
            ss2=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss1,ss2);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',accel_srss(i),accel_abssum(i));
                ss=sprintf('%s %s',ss,ss3);
            end

            ss3=sprintf('Pseudo Velocity (in/sec)');
            ss=sprintf('%s \n %s',ss,ss3);
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',pv_srss(i),pv_abssum(i));
                ss=sprintf('%s %s',ss,ss3);       
            end
           
            ss3=sprintf('Relative Displacement (in)');
            ss=sprintf('%s \n %s ',ss,ss3);    
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.3g \t %8.3g \n',rd_srss(i),rd_abssum(i));
                ss=sprintf('Maximum Values \n\n %s %s',ss,ss3);        
            end


            if(app.chplot>=2)

                xaccel_abssum=zeros(nxx,1);
                xaccel_srss=zeros(nxx,1);
              
                xpv_abssum=zeros(nxx,1);
                xpv_srss=zeros(nxx,1);  
            
                xrd_abssum=zeros(nxx,1);
                xrd_srss=zeros(nxx,1);
      
                xr=str2num(app.NodeNumberEditField.Value);
                [~,app.node_near]=min(abs(app.xx-xr));
                ev(1,:)=app.FullModeShapes(app.node_near,:);  
            

                for i=1:nxx

                    for j=1:num_fn
                    
                        PFM=app.part(j)*max(abs(ev(:,j)));
                    % fprintf(' i=%d  j=%d  PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,PFM,j,a_srs(j));
                    
                        xaccel_abssum(i)=xaccel_abssum(i)+abs(PFM*a_srs(j));
                        xaccel_srss(i)=xaccel_srss(i)+(PFM*a_srs(j))^2;
              
                        xpv_abssum(i)=xpv_abssum(i)+abs(PFM*pv_srs(j));
                        xpv_srss(i)=xpv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       xrd_abssum(i)=xrd_abssum(i)+abs(PFM*rd_srs(j));
                       xrd_srss(i)=xrd_srss(i)+(PFM*rd_srs(j))^2;   
                    end
                
                    xaccel_srss(i)=sqrt( xaccel_srss(i) );
                    xpv_srss(i)=sqrt(    xpv_srss(i) );
                    xrd_srss(i)=sqrt(    xrd_srss(i) );
               end

                xpv_abssum=xpv_abssum*vconv;
                xpv_srss=xpv_srss*vconv;   
            
                xrd_abssum=xrd_abssum*dconv;
                xrd_srss=xrd_srss*dconv;   

	            qs1=sprintf('Acceleration (G)');
                qs2=sprintf('SRSS \t Abssum');
                qs=sprintf('%s \n %s \n',qs1,qs2);
                
                for i=1:nxx
                    qs3=sprintf('%8.4g \t %8.4g \n',xaccel_srss(i),xaccel_abssum(i));
                    qs=sprintf('%s %s',qs,qs3);
                end
    
                qs3=sprintf('Pseudo Velocity (in/sec)');
                qs=sprintf('%s \n %s',qs,qs3);
                qs3=sprintf('SRSS \t Abssum');
                qs=sprintf('%s \n %s \n',qs,qs3);
                
                for i=1:nxx
                    qs3=sprintf('%8.4g \t %8.4g \n',xpv_srss(i),xpv_abssum(i));
                    qs=sprintf('%s %s',qs,qs3);       
                end
               
                qs3=sprintf('Relative Displacement (in)');
                qs=sprintf('%s \n %s ',qs,qs3);    
                qs3=sprintf('SRSS \t Abssum');
                qs=sprintf('%s \n %s \n',qs,qs3);
                
                for i=1:nxx
                    qs3=sprintf('%8.3g \t %8.3g \n',xrd_srss(i),xrd_abssum(i));
                    qs=sprintf('Values at %7.4g %s\n\n %s %s',xr,app.LU,qs,qs3);
            
                end
    
                ss=sprintf('%s \n %s',ss,qs);		

            end

            app.ResultsTextArea_srs.Value=ss;
            app.ResultsTextArea_srs.Visible='on';
            app.ResultsTextAreaLabel_srs.Visible='on'; 
        end

        % Button pushed function: UpdateListButton_srs
        function UpdateListButton_srsPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_srs
        function listbox_method_srsValueChanged(app, event)
            Items = char(app.listbox_method_srs.Items);
            im=app.listbox_method_srs.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               try
                    app.SelectInputArrayListBox_srs.Value=name;
               catch
               end
            end    
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_th(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_th(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: CalculateBaseInputArbitrary
        function CalculateBaseInputArbitraryButtonPushed(app, event)

            CloseFigures(app);

            app.ResponseResultsReadOnlyTextArea.Value='';
            pause(0.5);

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp(' ');
            disp(' * * * * * ');
            disp(' ');

            retdof_a(app);

            beam_FEA_base_arbitrary(app);

            app.RainflowFatigueButton.Enable='on';
               
        end

        % Button down function: ArbitraryTab
        function ArbitraryTabButtonDown(app, event)

            app.PlotListBox.Visible='off';
            app.PlotListBoxLabel.Visible='off';

            try
                app.NodeNumberEditField.Visible='on';
            catch
            end
            try
                app.ResponseLocationfromLeftEndLabel.Visible='on';
            catch
            end
            try
                app.ScriptwillfindnearestnodelocationLabel.Visible='on';  
            catch
            end

            app.node

        end

        % Button down function: PSDInputTab
        function PSDInputTabButtonDown(app, event)
            app.PlotListBox.Visible='on';
            app.PlotListBoxLabel.Visible='on';
        end

        % Button down function: SineSteadyStateTab
        function SineSteadyStateTabButtonDown(app, event)
            app.PlotListBox.Visible='on';
            app.PlotListBoxLabel.Visible='on';
        end

        % Button down function: MDOFSRSTab_2
        function MDOFSRSTab_2ButtonDown(app, event)
            app.PlotListBox.Visible='on';
            app.PlotListBoxLabel.Visible='on';
        end

        % Size changed function: ArbitraryTab
        function ArbitraryTabSizeChanged(app, event)
            app.PlotListBox.Visible='off';
            app.PlotListBoxLabel.Visible='off';

            % app.NodeNumberEditField.Visible='on';
            % app.ResponseLocationfromLeftEndLabel.Visible='on';
            % app.ScriptwillfindnearestnodelocationLabel.Visible='on';  

        end

        % Button down function: BaseExcitationResponseTab
        function BaseExcitationResponseTabButtonDown(app, event)
           SetResponseNode(app);
        end

        % Value changed function: SelectInputArrayListBox_srs
        function SelectInputArrayListBox_srsValueChanged(app, event)
            
            
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
          Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end   
            
        end

        % Value changed function: SetNodetoListBox
        function SetNodetoListBoxValueChanged(app, event)

            SetResponseNode(app);

 
        end

        % Value changing function: ResponseNodeEditField
        function ResponseNodeEditFieldValueChanging(app, event)
            retdof_a(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: OutputFrequencySpacingListBox
        function OutputFrequencySpacingListBoxValueChanged(app, event)
     
            app.f=[];
        end

        % Value changed function: OutputFrequencySpacingListBox_psd
        function OutputFrequencySpacingListBox_psdValueChanged(app, event)
          
            app.f=[];
        end

        % Button pushed function: RainflowFatigueButton
        function RainflowFatigueButtonPushed(app, event)
            rainflow_app
        end

        % Button pushed function: TransferFunctionforAppliedForceButton
        function TransferFunctionforAppliedForceButtonPushed(app, event)
            transfer_functions_from_modes_a
        end

        % Button pushed function: CalculateFRF
        function CalculateFRFButtonPushed(app, event)
      
            CloseFigures(app);
            
            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    
            if(isempty(app.dm))
                warndlg('Save Damping first');
                return;
            end         

            Items = strtrim(app.OutputFrequencySpacingListBox_frf.Items);
            im = strtrim(app.OutputFrequencySpacingListBox_frf.Value);
            ndf = find(strcmp(Items, im));
            
            app.fmin=str2num(app.StartFreqHzEditField_frf.Value);
            app.fmax=str2num(app.EndFreqHzEditField_frf.Value);

            if(app.fmin<=0.01 && ndf>=3)
                app.fmin=0.01;
            end    

            app.f=[];
            app.freq=[];

            app.f=zeros(10000,1);

            app.f(1)=app.fmin;
            
            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3)     
                    app.f(k)=app.f(k-1)*app.oct96;
                end
                if(ndf==4)     
                    app.f(k)=app.f(k-1)*app.oct48;
                end
                
                if(app.f(k)>app.fmax)
                    app.f(k)=app.fmax;
                    break;
                end
                
                k=k+1;
            end
            
            app.f=app.f(1:k);

            n2=str2num(app.NumberofModestoIncludeListBox_frf.Value);

            num_fn=min([ length(app.fn) n2 ]);
           
            force_node=str2num(app.InputForceNodeEditField.Value);
            resp_node=str2num(app.ResponseNodeEditField_frf.Value);

            if(force_node>app.num_nodes)
                ss=sprintf('Input Force dof must be <=%d',app.num_nodes);
                warndlg(ss);
                return;
            end    
            if(resp_node>app.num_nodes)
                ss=sprintf('Response dof must be <=%d',app.num_nodes);
                warndlg(ss);
                return;
            end   

            damp=app.dm;
                           
            %

            app.freq=app.f;
            app.omega=app.tpi*app.freq;

            %
            QE=app.TotalModeShapes;

            fnv=app.fn;
            dampv=damp;
            nf=length(app.freq);
            num_modes=length(fnv);
            %
            omn=app.tpi*fnv;
            app.omn2=omn.*omn;
            %
            omega2=zeros(nf,1);
            %
            for i=1:nf
                omega2(i)=(app.omega(i))^2;
            end
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            app.num_columns=num_fn;
            %
            sz=size(QE);
            if(app.num_columns>sz(2))
                app.num_columns=sz(2);
            end
            %
            i=2*resp_node-1;
            k=2*force_node-1;

            fprintf(' i=%d k=%d num_fn=%d nf=%d\n',i,k,num_fn,nf);
            
            %%%%%%

            minf=app.fmin;
            maxf=app.fmax;
            
            app.freq=fix_size(app.freq);
            
            iam=1;
                [H_disp_force]=...
                transfer_core(app.freq,fnv,dampv,QE,app.omn2,app.omega,omega2,iam,nf,app.num_columns,i,k,nrb);
                
                HM_disp=abs(H_disp_force);
                HP_disp=angle(H_disp_force)*180/pi;
                ymax=max(HM_disp);
                ymin=min(HM_disp);

                if(max(HM_disp)>0)
                    [app.fig_num]=...
                    transfer_from_modes_plots2(app.iu,iam,i,k,app.fig_num,minf,maxf,ymin,ymax,app.freq,HM_disp,HP_disp);
                end
                app.d_f_mag=[app.freq HM_disp];
                app.d_f_mag_phase=[app.freq HM_disp HP_disp];
                app.d_f_mag_complex=[app.freq H_disp_force];

            iam=2;
                [H_velox_force]=...
                transfer_core(app.freq,fnv,dampv,QE,app.omn2,app.omega,omega2,iam,nf,app.num_columns,i,k,nrb);
                HM_velox=abs(H_velox_force);
                HP_velox=angle(H_velox_force)*180/pi;
                ymax=max(HM_velox);
                ymin=min(HM_velox);    
                
                if(max(HM_velox)>0)
                    [app.fig_num]=...
                    transfer_from_modes_plots2(app.iu,iam,i,k,app.fig_num,minf,maxf,ymin,ymax,app.freq,HM_velox,HP_velox);   
                end

                app.v_f_mag=[app.freq HM_velox];
                app.v_f_mag_phase=[app.freq HM_velox HP_velox];
                app.v_f_mag_complex=[app.freq H_velox_force];
            
            iam=3;
                [H_accel_force]=...
                transfer_core(app.freq,fnv,dampv,QE,app.omn2,app.omega,omega2,iam,nf,app.num_columns,i,k,nrb);
            
                if(app.iu==1)
                    app.scale=386;
                else
                    app.scale=9.81;
                end
                
                H_accel_force= H_accel_force/app.scale;
            
                app.HM_accel=abs(H_accel_force); 
                HP_accel=angle(H_accel_force)*180/pi;
                ymax=max(app.HM_accel);
                ymin=min(app.HM_accel);    

                if(max(app.HM_accel)>0)
                    [app.fig_num]=...
                    transfer_from_modes_plots2(app.iu,iam,i,k,app.fig_num,minf,maxf,ymin,ymax,app.freq,app.HM_accel,HP_accel);
                end
                app.a_f_mag=[app.freq app.HM_accel];
                app.a_f_mag_phase=[app.freq app.HM_accel HP_accel];
                app.a_f_mag_complex=[app.freq H_accel_force];
            
            %    
            %%%%%%
            %

            disp('ref 1')

            try
            
                % [app.H_stress]=...
                % transfer_beam_stress_fea_alt(app.freq,fnv,dampv,QE,app.omn2,nf,app.num_columns,i,k,nrb,app.xx,app.E,app.cna,app.L);

                iam=1;

                app.ndof=2*app.num_nodes;
      
                H_df=zeros(nf,4);

                LL=mean(diff(app.xx));
                
                      
            catch
                disp(' LL B failed');
                return;
            end

            try
                kv=1;  
                if(i<app.ndof)
                   [B]=beam_stress_B(0,LL);
                   for j=i:i+3 
                        [H_df(:,kv)]=...
                        transfer_core(app.freq,fnv,dampv,QE,app.omn2,app.omega,omega2,iam,nf,app.num_columns,j,k,nrb);
                        kv=kv+1;
                   end

                else
                   [B]=beam_stress_B(1,LL); 
                   for j=(i-3):i 
                        [H_df(:,kv)]=...
                        transfer_core(app.freq,fnv,dampv,QE,app.omn2,app.omega,omega2,iam,nf,app.num_columns,j,k,nrb);
                        kv=kv+1;
                   end
                end

                d=transpose([H_df(:,1) H_df(:,2) H_df(:,3) H_df(:,4) ]); 
                Bd=B*d;

            catch
                disp(' Bd failed')
                return;
            end
     
            try
                sf=app.cna*app.E*Bd;
                sf=sf';
            catch
                app.cna
                app.E
                size(Bd)
                disp(' sf failed 1 ')
                return;
            end

            try
                app.HM_stress=abs(sf); 
                HP_stress=-angle(sf)*180/pi;
                ymax=max(app.HM_stress);
                ymin=min(app.HM_stress);
            catch
                disp(' HP_stress failed')
                return;
            end

            try

                iam=6;

                if(ymax>0)
                    [app.fig_num]=...
                    transfer_from_modes_plots2(app.iu,iam,i,k,app.fig_num,minf,maxf,ymin,ymax,app.freq,app.HM_stress,HP_stress);    
                end

            catch
                disp('stress plot failed')
            end
            try
                app.stress_f_mag=[app.freq app.HM_stress];
                app.stress_f_mag_phase=[app.freq app.HM_stress HP_stress];
                app.stress_f_mag_complex=[app.freq sf];
            catch
                disp('stress save failed')
            end
                
        end

        % Value changed function: SetResponseNodetoListBox
        function SetResponseNodetoListBoxValueChanged(app, event)

            SetResponseNodeFRF(app);

        end

        % Button down function: AppliedForceResponseTab
        function AppliedForceResponseTabButtonDown(app, event)
            SetResponseNodeFRF(app);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            
            Items = strtrim(app.FormatListBox.Items);
            im = strtrim(app.FormatListBox.Value);
            nf = find(strcmp(Items, im));

            Items = strtrim(app.FRFTypeListBox.Items);
            im = strtrim(app.FRFTypeListBox.Value);
            nat = find(strcmp(Items, im));


            output_name=app.FRFOutputArrayNameEditField.Value;

            if(nat==1 && nf==1)
                data=app.a_f_mag;
            end
            if(nat==1 && nf==2)
                data=app.a_f_mag_phase;    
            end
            if(nat==1 && nf==3)
                data=app.a_f_mag_complex;       
            end
            
            
            if(nat==2 && nf==1)
                data=app.v_f_mag;    
            end
            if(nat==2 && nf==2)
                data=app.v_f_mag_phase;       
            end
            if(nat==2 && nf==3)
                data=app.v_f_mag_complex;      
            end
            
            
            if(nat==3 && nf==1)
                data=app.d_f_mag;        
            end
            if(nat==3 && nf==2)
                data=app.d_f_mag_phase;       
            end
            if(nat==3 && nf==3)
                data=app.d_f_mag_complex;       
            end
            
            
            if(nat==4 && nf==1)
                data=app.stress_f_mag;
            end
            if(nat==4 && nf==2)
                data=app.stress_f_mag_phase;    
            end
            if(nat==4 && nf==3)
                data=app.stress_f_mag_complex;       
            end
            
            
            assignin('base', output_name, data);
             
            msgbox('Data saved');


        end

        % Button pushed function: SetfnButton
        function SetfnButtonPushed(app, event)
            app.FreqEditField.Value=sprintf('%7.4g',app.fn(1));
        end

        % Button pushed function: CalculateButton_force_sine
        function CalculateButton_force_sinePushed(app, event)
            CloseFigures(app);

            app.stress=[];
            
            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    
            if(isempty(app.dm))
                warndlg('Save Damping first');
                return;
            end   

            app.ndof=2*(app.ne+1);
            
            F=str2num(app.ForcelbfEditField.Value);
            dur=str2num(app.DurationsecEditField_force_sine.Value);
            app.sr=str2num(app.SampleRateEditField_force.Value);
            app.freq=str2num(app.FreqEditField.Value);

            app.dt=1/app.sr;

            ntt=floor(dur/app.dt);

            t=(0:ntt-1)*app.dt;

            t=fix_size(t);

            omegaq=2*pi*app.freq;

            amp=F*sin(omegaq*t);

            %%%

            ntt=length(t);
            app.THM=[t amp];

            n2=str2num(app.NumberofModestoIncludeListBox_frf.Value);
           
            force_node=str2num(app.InputForceNodeEditField.Value);
            resp_node=str2num(app.ResponseNodeEditField_frf.Value);

            force_dof=2*force_node-1;
            resp_dof=2*resp_node-1;

            if(force_node>app.num_nodes)
                ss=sprintf('Input Force dof must be <=%d',app.num_nodes);
                warndlg(ss);
                return;
            end    
            if(resp_node>app.num_nodes)
                ss=sprintf('Response dof must be <=%d',app.num_nodes);
                warndlg(ss);
                return;
            end   

            n2=min([n2 length(app.fn)]);

            ff=app.freq;

            n=zeros(ntt,n2);
            nvelox=zeros(ntt,n2);
            naccel=zeros(ntt,n2);

            disp('ref nm2 ')
            size(app.TotalModeShapes)

            MMST=app.TotalModeShapes';

            disp('ref nm3 ')
            size(MMST)


            try
                fq=zeros(app.ndof,1);
            catch
                disp('ref 1')
                app.ndof
                return
            end

            try
                fq(force_dof,1)=F;
            catch
                disp('ref 2')
                return                
            end

            for i=1:n2

                try
                    F=MMST(i,:)*fq;
                catch
                    disp('ref 3')
                    app.ndof
                    size(fq)
                    size(MMST(i,:))
                    return;
                end

                [~,nna,nnv,nnd,~,~]=...
                    vibrationdata_sine_pulse_force_alt(F,t,app.fn(i),app.dm(i),ff,0,0);

                naccel(:,i)=nna;
                nvelox(:,i)=nnv;
                n(:,i)=nnd;
            end

            W=app.TotalModeShapes(:,1:n2);


            dd=(W*n')';
            vv=(W*nvelox')';
            aa=(W*naccel')';

            if(app.iu==1)             
               aa=aa/386; 
               VU='in/sec';
               DU='in';
               DS='psi';
            else    
               aa=aa/9.81;
               dd=dd/1000;
               VU='m/sec';
               DU='mm'; 
               DS='Pa';          
            end 

            cnaE=app.cna*app.E;

            disp('stress ');
            size(dd)

            for nk=1:app.ne
            
                LL=abs(app.xx(nk)-app.xx(nk+1));
                    
                [B]=beam_stress_B(0,LL);
                TL=2*nk-1;
 
                d = transpose([dd(:,TL:TL+3)]);
                app.stress(:, nk) = cnaE * B * d;
                fprintf(' %d  %8.4g \n',nk,std(app.stress(:, nk)));

            end

           % stress at right end  
            app.stress(:,end+1)=app.stress(:,end);

            figure(2);

            aa=aa(:,resp_dof);
            vv=vv(:,resp_dof);
            dd=dd(:,resp_dof);

            app.stress=app.stress(:,resp_node);
            
            try
                plot(t,aa);
            catch
                warndlg(' Plot failed:  Check input parameters');
                return;
            end

            
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            maxa=max(aa);
            mina=min(aa);
            out1=sprintf('Acceleration  Node %d \n Peaks (%6.3g G, %6.3g G)',resp_node,maxa,mina);
            title(out1);
            fprintf('\n Acceleration:  %6.3g G   %6.3g G \n\n',maxa,mina);
            grid on;

        %    
            figure(3);
            plot(t,vv);
            xlabel('Time (sec)');
            max_v=max(vv);
            min_v=min(vv);
            out1=sprintf('Velocity   Node %d \n Peaks (%6.3g %s, %6.3g %s)',resp_node,max_v,VU,min_v,VU);            
            ylabel(sprintf('Velocity (%s)',VU));        
            title(out1);
            grid on;
        %    
            figure(4);
            plot(t,dd);
            xlabel('Time (sec)');
            max_d=max(dd);
            min_d=min(dd);
            out1=sprintf('Displacement  Node %d  \n Peaks (%6.3g %s, %6.3g %s)',resp_node,max_d,DU,min_d,DU);            
            ylabel(sprintf('Displacement (%s)',DU));        
            title(out1);
            grid on;
        %    
            figure(5);
            plot(t,app.stress);
            xlabel('Time (sec)');
            max_s=max(app.stress);
            min_s=min(app.stress);
            out1=sprintf('Stress  Node %d \n Peaks (%6.4g %s, %6.4g %s)',resp_node,max_s,DS,min_s,DS);            
            ylabel(sprintf('Stress (%s)',DS));        
            title(out1);
            grid on;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeamBendingFEAUIFigure = uifigure('Visible', 'off');
            app.BeamBendingFEAUIFigure.Name = 'Beam Bending FEA';
            app.beam_bending_FEA_aver19byTomIrvineLabel = uilabel(app.BeamBendingFEAUIFigure);
            app.beam_bending_FEA_aver19byTomIrvineLabel.Text = 'beam_bending_FEA_a  ver 1.9   by Tom Irvine';
            app.TabGroup = uitabgroup(app.BeamBendingFEAUIFigure);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Solid Cylinder';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Free', 'Pinned', 'Fixed'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Pinned';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Free', 'Pinned', 'Fixed'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Pinned';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'text');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.NumberofElementsLabel = uilabel(app.GeometryBCsTab);
            app.NumberofElementsLabel.Text = 'Number of Elements *';
            app.NumberofElementsEditField = uieditfield(app.GeometryBCsTab, 'text');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.Label = uilabel(app.GeometryBCsTab);
            app.Label.Text = {'* Increase number of elements until response '; '  converges, especially for stress'};
            app.UnitsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryBCsTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MaterialAddedMassTab = uitab(app.TabGroup);
            app.MaterialAddedMassTab.Title = 'Material & Added Mass';
            app.MaterialListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialAddedMassTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNonstructuralMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.UniformNonstructuralMasslbmEditField.ValueChangedFcn = createCallbackFcn(app, @UniformNonstructuralMasslbmEditFieldValueChanged, true);
            app.UniformNonstructuralMasslbmEditField.Value = '0';
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangedFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanged, true);
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangedFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanged, true);
            app.NumberofPointMassesListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBoxLabel.Text = 'Number of Point Masses';
            app.NumberofPointMassesListBox = uilistbox(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBox.Items = {'0', '1'};
            app.NumberofPointMassesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPointMassesListBoxValueChanged, true);
            app.NumberofPointMassesListBox.Value = '0';
            app.PointMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMasslbmEditFieldLabel.Text = 'Point Mass (lbm)';
            app.PointMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.PointMassLocationfromLeftEndinEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMassLocationfromLeftEndinEditFieldLabel.Text = 'Point Mass Location from Left End (in)';
            app.PointMassLocationfromLeftEndinEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.CalculateNaturalFrequenciesButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesButtonPushed, true);
            app.CalculateNaturalFrequenciesButton.Text = 'Calculate Natural Frequencies ';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.SelectModeforPlotListBox.Value = '1';
            app.UITable_fn = uitable(app.NaturalFrequenciesTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            app.UITable_fn.RowName = {};
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.BaseExcitationResponseTab = uitab(app.TabGroup);
            app.BaseExcitationResponseTab.Title = 'Base Excitation Response';
            app.BaseExcitationResponseTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationResponseTabButtonDown, true);
            app.TabGroup2 = uitabgroup(app.BaseExcitationResponseTab);
            app.TransmissibilityTab = uitab(app.TabGroup2);
            app.TransmissibilityTab.Title = 'Transmissibility';
            app.TransmissibilityTab.ButtonDownFcn = createCallbackFcn(app, @TransmissibilityTabButtonDown, true);
            app.CalculateBaseInputTrans = uibutton(app.TransmissibilityTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.StartFreqHzEditField.Value = '10';
            app.EndFreqHzEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.EndFreqHzEditField.Value = '2000';
            app.OutputFrequencySpacingListBoxLabel = uilabel(app.TransmissibilityTab);
            app.OutputFrequencySpacingListBoxLabel.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox = uilistbox(app.TransmissibilityTab);
            app.OutputFrequencySpacingListBox.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave', '1/48 octave'};
            app.OutputFrequencySpacingListBox.ValueChangedFcn = createCallbackFcn(app, @OutputFrequencySpacingListBoxValueChanged, true);
            app.OutputFrequencySpacingListBox.Value = 'Constant 1.0 Hz';
            app.TransOutputArraysReadOnlyTextAreaLabel = uilabel(app.TransmissibilityTab);
            app.TransOutputArraysReadOnlyTextAreaLabel.Text = 'Trans Output Arrays, Read Only';
            app.TransOutputArraysReadOnlyTextArea = uitextarea(app.TransmissibilityTab);
            app.TransOutputArraysReadOnlyTextArea.Editable = 'off';
            app.OutputArrayPrefixEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.OutputArrayPrefixEditFieldLabel.Text = 'Output Array Prefix';
            app.OutputArrayPrefixEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.OutputArrayPrefixEditField.Value = 'beam_trans';
            app.PSDInputTab = uitab(app.TabGroup2);
            app.PSDInputTab.Title = 'PSD Input';
            app.PSDInputTab.ButtonDownFcn = createCallbackFcn(app, @PSDInputTabButtonDown, true);
            app.TabGroup5 = uitabgroup(app.PSDInputTab);
            app.PSDInputTab_2 = uitab(app.TabGroup5);
            app.PSDInputTab_2.Title = 'PSD Input';
            app.listbox_method_psd = uilistbox(app.PSDInputTab_2);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.PSDInputTab_2);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.UpdateListButton_psd = uibutton(app.PSDInputTab_2, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.PSDInputTab_2);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.SelectInputArrayListBox_2Label = uilabel(app.PSDInputTab_2);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.PSDInputTab_2);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.SelectInputArrayListBox_psd.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_psdValueChanged, true);
            app.CalculateTab_2 = uitab(app.TabGroup5);
            app.CalculateTab_2.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputFrequencySpacingListBox_2Label = uilabel(app.CalculateTab_2);
            app.OutputFrequencySpacingListBox_2Label.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox_psd = uilistbox(app.CalculateTab_2);
            app.OutputFrequencySpacingListBox_psd.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave', '1/48 octave'};
            app.OutputFrequencySpacingListBox_psd.ValueChangedFcn = createCallbackFcn(app, @OutputFrequencySpacingListBox_psdValueChanged, true);
            app.OutputFrequencySpacingListBox_psd.Value = 'Constant 1.0 Hz';
            app.OutputPSDArrayPrefixLabel = uilabel(app.CalculateTab_2);
            app.OutputPSDArrayPrefixLabel.Text = 'Output PSD Array Prefix';
            app.OutputPSDArrayPrefixEditField = uieditfield(app.CalculateTab_2, 'text');
            app.OutputPSDArrayPrefixEditField.Value = 'beam_psd';
            app.PSDOutputArraysTextAreaLabel = uilabel(app.CalculateTab_2);
            app.PSDOutputArraysTextAreaLabel.Text = 'PSD Output Arrays';
            app.PSDOutputArraysTextArea = uitextarea(app.CalculateTab_2);
            app.PSDOutputArraysTextArea.Editable = 'off';
            app.SineSteadyStateTab = uitab(app.TabGroup2);
            app.SineSteadyStateTab.Title = 'Sine Steady State';
            app.SineSteadyStateTab.ButtonDownFcn = createCallbackFcn(app, @SineSteadyStateTabButtonDown, true);
            app.SettoFirstfnButton = uibutton(app.SineSteadyStateTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.CalculateBaseInputSine = uibutton(app.SineSteadyStateTab, 'push');
            app.CalculateBaseInputSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSineButtonPushed, true);
            app.CalculateBaseInputSine.Text = 'Calculate';
            app.BaseAccelerationGEditFieldLabel = uilabel(app.SineSteadyStateTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.SineSteadyStateTab, 'text');
            app.BaseAccelerationGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelerationGEditFieldValueChanging, true);
            app.BaseAccelerationGEditField.Value = '1';
            app.ExcitationFreqHzEditFieldLabel = uilabel(app.SineSteadyStateTab);
            app.ExcitationFreqHzEditFieldLabel.Text = 'Excitation Freq (Hz)';
            app.ExcitationFreqHzEditField = uieditfield(app.SineSteadyStateTab, 'text');
            app.ExcitationFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @ExcitationFreqHzEditFieldValueChanging, true);
            app.ResultsTextAreaLabel = uilabel(app.SineSteadyStateTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.SineSteadyStateTab);
            app.ArbitraryTab = uitab(app.TabGroup2);
            app.ArbitraryTab.SizeChangedFcn = createCallbackFcn(app, @ArbitraryTabSizeChanged, true);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.ArbitraryTab.ButtonDownFcn = createCallbackFcn(app, @ArbitraryTabButtonDown, true);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel = uilabel(app.ArbitraryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.CalculateBaseInputArbitrary = uibutton(app.ArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitraryButtonPushed, true);
            app.CalculateBaseInputArbitrary.Text = 'Calculate';
            app.listbox_method = uilistbox(app.ArbitraryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ArbitraryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ArbitraryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ArbitraryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ArbitraryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ResponseResultsReadOnlyLabel = uilabel(app.ArbitraryTab);
            app.ResponseResultsReadOnlyLabel.Text = 'Response Results, Read Only';
            app.ResponseResultsReadOnlyTextArea = uitextarea(app.ArbitraryTab);
            app.ResponseResultsReadOnlyTextArea.Editable = 'off';
            app.RainflowFatigueButton = uibutton(app.ArbitraryTab, 'push');
            app.RainflowFatigueButton.ButtonPushedFcn = createCallbackFcn(app, @RainflowFatigueButtonPushed, true);
            app.RainflowFatigueButton.Text = 'Rainflow Fatigue ';
            app.MDOFSRSTab_2 = uitab(app.TabGroup2);
            app.MDOFSRSTab_2.Title = 'MDOF SRS';
            app.MDOFSRSTab_2.ButtonDownFcn = createCallbackFcn(app, @MDOFSRSTab_2ButtonDown, true);
            app.CalculateBaseInputMDOFSRS = uibutton(app.MDOFSRSTab_2, 'push');
            app.CalculateBaseInputMDOFSRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputMDOFSRSButtonPushed, true);
            app.CalculateBaseInputMDOFSRS.Text = 'Calculate';
            app.listbox_method_srs = uilistbox(app.MDOFSRSTab_2);
            app.listbox_method_srs.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_srs.ValueChangedFcn = createCallbackFcn(app, @listbox_method_srsValueChanged, true);
            app.listbox_method_srs.Tag = 'listbox_method';
            app.listbox_method_srs.Value = 'Array Preloaded in Matlab';
            app.text1_srs = uilabel(app.MDOFSRSTab_2);
            app.text1_srs.Tag = 'text1';
            app.text1_srs.Text = 'Select Data Input Method';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.MDOFSRSTab_2);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)'; ''};
            app.UpdateListButton_srs = uibutton(app.MDOFSRSTab_2, 'push');
            app.UpdateListButton_srs.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srsPushed, true);
            app.UpdateListButton_srs.Text = 'Update List';
            app.SpecificationQEditFieldLabel = uilabel(app.MDOFSRSTab_2);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.MDOFSRSTab_2, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.SelectInputArrayListBox_2Label_2 = uilabel(app.MDOFSRSTab_2);
            app.SelectInputArrayListBox_2Label_2.Text = 'Select Input Array';
            app.SelectInputArrayListBox_srs = uilistbox(app.MDOFSRSTab_2);
            app.SelectInputArrayListBox_srs.Items = {'Item 1'};
            app.SelectInputArrayListBox_srs.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_srsValueChanged, true);
            app.ResultsTextAreaLabel_srs = uilabel(app.MDOFSRSTab_2);
            app.ResultsTextAreaLabel_srs.Text = 'Results';
            app.ResultsTextArea_srs = uitextarea(app.MDOFSRSTab_2);
            app.NumberofModestoIncludeLabel = uilabel(app.BaseExcitationResponseTab);
            app.NumberofModestoIncludeLabel.Text = {'Number of '; 'Modes '; 'to Include'; ''};
            app.NumberofModestoIncludeListBox = uilistbox(app.BaseExcitationResponseTab);
            app.NumberofModestoIncludeListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModestoIncludeListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofModestoIncludeListBoxValueChanged, true);
            app.NumberofModestoIncludeListBox.Value = '12';
            app.PlotListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.BaseExcitationResponseTab);
            app.PlotListBox.Items = {'Maximums', 'Response Location Only', 'Maximums & Response Locations'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Maximums';
            app.ResponseNodeEditFieldLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResponseNodeEditFieldLabel.Text = 'Response Node';
            app.ResponseNodeEditField = uieditfield(app.BaseExcitationResponseTab, 'text');
            app.ResponseNodeEditField.ValueChangingFcn = createCallbackFcn(app, @ResponseNodeEditFieldValueChanging, true);
            app.SetNodetoListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.SetNodetoListBoxLabel.Text = 'Set Node to';
            app.SetNodetoListBox = uilistbox(app.BaseExcitationResponseTab);
            app.SetNodetoListBox.Items = {'Nearest to Center', 'Max Eigen Mode 1', 'Max Eigen Mode 2', 'Max Eigen Mode 3', 'Other'};
            app.SetNodetoListBox.ValueChangedFcn = createCallbackFcn(app, @SetNodetoListBoxValueChanged, true);
            app.SetNodetoListBox.Value = 'Nearest to Center';
            app.AppliedForceResponseTab = uitab(app.TabGroup);
            app.AppliedForceResponseTab.Title = 'Applied Force Response';
            app.AppliedForceResponseTab.ButtonDownFcn = createCallbackFcn(app, @AppliedForceResponseTabButtonDown, true);
            app.TabGroup3 = uitabgroup(app.AppliedForceResponseTab);
            app.TransferFunctionTab = uitab(app.TabGroup3);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.TabGroup4 = uitabgroup(app.TransferFunctionTab);
            app.CalculateTab = uitab(app.TabGroup4);
            app.CalculateTab.Title = 'Calculate';
            app.StartFreqHzEditField_2Label = uilabel(app.CalculateTab);
            app.StartFreqHzEditField_2Label.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField_frf = uieditfield(app.CalculateTab, 'text');
            app.StartFreqHzEditField_frf.Value = '10';
            app.EndFreqHzEditField_2Label = uilabel(app.CalculateTab);
            app.EndFreqHzEditField_2Label.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField_frf = uieditfield(app.CalculateTab, 'text');
            app.EndFreqHzEditField_frf.Value = '2000';
            app.OutputFrequencySpacingListBox_2Label_2 = uilabel(app.CalculateTab);
            app.OutputFrequencySpacingListBox_2Label_2.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox_frf = uilistbox(app.CalculateTab);
            app.OutputFrequencySpacingListBox_frf.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave', '1/48 octave'};
            app.OutputFrequencySpacingListBox_frf.Value = 'Constant 1.0 Hz';
            app.CalculateFRF = uibutton(app.CalculateTab, 'push');
            app.CalculateFRF.ButtonPushedFcn = createCallbackFcn(app, @CalculateFRFButtonPushed, true);
            app.CalculateFRF.Text = 'Calculate';
            app.SaveFRFTab = uitab(app.TabGroup4);
            app.SaveFRFTab.Title = 'Save FRF';
            app.FRFOutputArrayNameEditFieldLabel = uilabel(app.SaveFRFTab);
            app.FRFOutputArrayNameEditFieldLabel.Text = 'FRF Output Array Name';
            app.FRFOutputArrayNameEditField = uieditfield(app.SaveFRFTab, 'text');
            app.FormatListBoxLabel = uilabel(app.SaveFRFTab);
            app.FormatListBoxLabel.Text = 'Format';
            app.FormatListBox = uilistbox(app.SaveFRFTab);
            app.FormatListBox.Items = {'Freq & Magnitude        ', 'Freq, Magnitude, Phase  ', 'Freq, Complex Magnitude', ''};
            app.FormatListBox.Value = 'Freq & Magnitude        ';
            app.FRFTypeListBoxLabel = uilabel(app.SaveFRFTab);
            app.FRFTypeListBoxLabel.Text = 'FRF Type';
            app.FRFTypeListBox = uilistbox(app.SaveFRFTab);
            app.FRFTypeListBox.Items = {'Acceleration/Force', 'Velocity/Force    ', 'Displacement/Force', 'Stress/Force          '};
            app.FRFTypeListBox.Value = 'Acceleration/Force';
            app.SaveButton_2 = uibutton(app.SaveFRFTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SineTimeHistoryTab = uitab(app.TabGroup3);
            app.SineTimeHistoryTab.Title = 'Sine Time History';
            app.CalculateButton_force_sine = uibutton(app.SineTimeHistoryTab, 'push');
            app.CalculateButton_force_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_force_sinePushed, true);
            app.CalculateButton_force_sine.Text = 'Calculate';
            app.SetfnButton = uibutton(app.SineTimeHistoryTab, 'push');
            app.SetfnButton.ButtonPushedFcn = createCallbackFcn(app, @SetfnButtonPushed, true);
            app.SetfnButton.Text = 'Set to Fundamental fn';
            app.ForcelbfEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.ForcelbfEditFieldLabel.Text = 'Force (lbf)';
            app.ForcelbfEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.DurationsecEditField_2Label = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_force_sine = uieditfield(app.SineTimeHistoryTab, 'text');
            app.DurationsecEditField_2Label_2 = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label_2.Text = 'Freq (Hz)';
            app.FreqEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.DurationsecEditField_2Label_3 = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label_3.Text = 'Sample Rate (Hz)';
            app.SampleRateEditField_force = uieditfield(app.SineTimeHistoryTab, 'text');
            app.NumberofModestoIncludeListBox_2Label = uilabel(app.AppliedForceResponseTab);
            app.NumberofModestoIncludeListBox_2Label.Text = {'Number of '; 'Modes '; 'to Include'; ''};
            app.NumberofModestoIncludeListBox_frf = uilistbox(app.AppliedForceResponseTab);
            app.NumberofModestoIncludeListBox_frf.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModestoIncludeListBox_frf.Value = '1';
            app.ResponseNodeEditField_2Label = uilabel(app.AppliedForceResponseTab);
            app.ResponseNodeEditField_2Label.Text = 'Response Node';
            app.ResponseNodeEditField_frf = uieditfield(app.AppliedForceResponseTab, 'text');
            app.SetResponseNodetoListBoxLabel = uilabel(app.AppliedForceResponseTab);
            app.SetResponseNodetoListBoxLabel.Text = 'Set Response Node to';
            app.SetResponseNodetoListBox = uilistbox(app.AppliedForceResponseTab);
            app.SetResponseNodetoListBox.Items = {'Nearest to Center', 'Max Eigen Mode 1', 'Max Eigen Mode 2', 'Max Eigen Mode 3', 'Other'};
            app.SetResponseNodetoListBox.ValueChangedFcn = createCallbackFcn(app, @SetResponseNodetoListBoxValueChanged, true);
            app.SetResponseNodetoListBox.Value = 'Nearest to Center';
            app.InputForceNodeEditFieldLabel = uilabel(app.AppliedForceResponseTab);
            app.InputForceNodeEditFieldLabel.Text = 'Input Force Node';
            app.InputForceNodeEditField = uieditfield(app.AppliedForceResponseTab, 'text');
            app.NodePositionTab = uitab(app.TabGroup);
            app.NodePositionTab.Title = 'Node & Position';
            app.UITable_nodes = uitable(app.NodePositionTab);
            app.UITable_nodes.ColumnName = {'x (in)'; ''};
            app.UITable_nodes.RowName = {};
            app.NodeNumberPositionLabel = uilabel(app.NodePositionTab);
            app.NodeNumberPositionLabel.Text = 'Node Number & Position';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MassArrayEditFieldLabel = uilabel(app.ExportTab);
            app.MassArrayEditFieldLabel.Text = 'Mass Array';
            app.MassArrayEditField = uieditfield(app.ExportTab, 'text');
            app.MassArrayEditField.Value = 'bending_mass';
            app.StiffnessArrayEditFieldLabel = uilabel(app.ExportTab);
            app.StiffnessArrayEditFieldLabel.Text = 'Stiffness Array';
            app.StiffnessArrayEditField = uieditfield(app.ExportTab, 'text');
            app.StiffnessArrayEditField.Value = 'bending_stiffness';
            app.TransferFunctionforAppliedForceButton = uibutton(app.ExportTab, 'push');
            app.TransferFunctionforAppliedForceButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFunctionforAppliedForceButtonPushed, true);
            app.TransferFunctionforAppliedForceButton.Text = 'Transfer Function for Applied Force';
            app.ModeShapesEditFieldLabel = uilabel(app.ExportTab);
            app.ModeShapesEditFieldLabel.Text = 'Mode Shapes';
            app.ModeShapesEditField = uieditfield(app.ExportTab, 'text');
            app.ModeShapesEditField.Value = 'bending_modeshapes';
            app.NaturalFrequenciesEditFieldLabel = uilabel(app.ExportTab);
            app.NaturalFrequenciesEditFieldLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesEditField = uieditfield(app.ExportTab, 'text');
            app.NaturalFrequenciesEditField.Value = 'bending_fn';
            app.SaveModelButton = uibutton(app.BeamBendingFEAUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.BeamBendingFEAUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.ReturnButton = uibutton(app.BeamBendingFEAUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ContextMenu = uicontextmenu(app.BeamBendingFEAUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.text1_srs.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.BeamBendingFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beam_bending_FEA_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BeamBendingFEAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BeamBendingFEAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamBendingFEAUIFigure)
        end
    end
end