classdef beam_bending_FEA_a_old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIBeamBendingFEAUIFigure        matlab.ui.Figure
        beam_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.EditField
        EditField_1Label                matlab.ui.control.Label
        LengthinEditField_2             matlab.ui.control.EditField
        LengthinEditField_2Label        matlab.ui.control.Label
        NumberofElementsListBox         matlab.ui.control.ListBox
        NumberofElementsListBoxLabel    matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        ImageIBeam                      matlab.ui.control.Image
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MaterialAddedMassTab            matlab.ui.container.Tab
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        PointMassLocationfromLeftEndinEditField  matlab.ui.control.EditField
        PointMassLocationfromLeftEndinEditFieldLabel  matlab.ui.control.Label
        PointMasslbmEditField           matlab.ui.control.EditField
        PointMasslbmEditFieldLabel      matlab.ui.control.Label
        UniformNonstructuralMasslbmEditField  matlab.ui.control.EditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        NumberofPointMassesListBox      matlab.ui.control.ListBox
        NumberofPointMassesListBoxLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialLabel                   matlab.ui.control.Label
        NaturalFrequenciesTab           matlab.ui.container.Tab
        ExportMassStiffnessArraysEditField  matlab.ui.control.EditField
        ExportMassStiffnessArraysEditFieldLabel  matlab.ui.control.Label
        UITable_fn                      matlab.ui.control.Table
        CalculateNaturalFrequenciesPartitionButton  matlab.ui.control.Button
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        PlotModeShapeButton             matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        BaseExcitationResponseTab       matlab.ui.container.Tab
        PlotListBox                     matlab.ui.control.ListBox
        PlotListBoxLabel                matlab.ui.control.Label
        ResponseLocationfromLeftEndEditField  matlab.ui.control.EditField
        ResponseLocationfromLeftEndEditFieldLabel  matlab.ui.control.Label
        inchLabel                       matlab.ui.control.Label
        ScriptwillfindnearestnodelocationLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputFunctionListBox           matlab.ui.control.ListBox
        OutputFunctionListBoxLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ImageBeamBase                   matlab.ui.control.Image
        NumberofModestoIncludeListBox   matlab.ui.control.ListBox
        NumberofModesListBox_3Label_3   matlab.ui.control.Label
        TabGroupBase                    matlab.ui.container.TabGroup
        TransTab                        matlab.ui.container.Tab
        OutputArrayPrefixEditField      matlab.ui.control.EditField
        OutputArrayPrefixEditFieldLabel  matlab.ui.control.Label
        CalculateBaseInputTrans         matlab.ui.control.Button
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        EndFrequencyHzEditField_2Label  matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        StartFrequencyHzEditField_2Label  matlab.ui.control.Label
        SineTab                         matlab.ui.container.Tab
        ExcitationFreqHzEditField       matlab.ui.control.EditField
        ExcitationFreqHzEditFieldLabel  matlab.ui.control.Label
        BaseAccelerationGEditField      matlab.ui.control.EditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        CalculateBaseInputSine          matlab.ui.control.Button
        SettoFirstfnButton              matlab.ui.control.Button
        HalfSineTab                     matlab.ui.container.Tab
        CalculateResponseButton         matlab.ui.control.Button
        TotalResponseDurationsecEditField_3  matlab.ui.control.EditField
        TotalResponseDurationsecEditField_3Label  matlab.ui.control.Label
        PulseDurationsecEditField_3     matlab.ui.control.EditField
        PulseDurationsecEditField_3Label  matlab.ui.control.Label
        BaseInputAccelGEditField_3      matlab.ui.control.EditField
        BaseInputAccelGEditField_3Label  matlab.ui.control.Label
        PSDTab                          matlab.ui.container.Tab
        UpdateListButton_psd            matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculateBaseInputPSD_2         matlab.ui.control.Button
        ArbitraryTab                    matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateBaseInputArbitrary_2   matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecaccelGLabel_2  matlab.ui.control.Label
        MDOFSRSTab                      matlab.ui.container.Tab
        UpdateListButton_srs            matlab.ui.control.Button
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        SelectInputArrayListBox_srs     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_2  matlab.ui.control.Label
        text1_srs                       matlab.ui.control.Label
        listbox_method_srs              matlab.ui.control.ListBox
        CalculateBaseInputMDOFSRS_2     matlab.ui.control.Button
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        BC                 % Boundary condition
        C                  % Modal constant
        E                  % Elastic modulus
        FullModeShapes     %
        HM_accel           %
        HM_pv              %
        HM_rd              %
        HM_stress          %       
        HM_stress_response %
        L                  % Length
        LBC                % Left boundary condition
        LU                 %
        ModeShapes         % Mode Shapes
        MOI                % Area moment of intertia
        MST                % Mode Shapes Transpose
        RBC                % Right boundary condition
        THM                %
        TT                 %
        TZ_tracking_array  %
        ZZ                 % Mode shape

        absolute_accel   % 
        acc              %
        acceleration     %
        adisp            %
        atrans           %
        bending_moment   % 
        bending_stress   % 
        beta             % eigenvalue parameter
        chplot           %
        cna              % Distance to neutral axis
        ddd              %
        ddisp            %
        dm               % Damping
        dof              % degrees-of-freedom
        dof_status       % dof status
        dt               %
        dx               % element length array
        fig_num          %
        fmin             %
        fmax             %
        fn               % Natural frequencies
        freq             %
        global_mass      %
        global_mass_unc  %  
        global_stiff     %
        global_stiff_unc %
        iflag            %
        imat             %
        iu               % iu=1 for English units
        kflag            %
        method           %
        na               %
        nde              %
        ne               % number of elements
        nem              %
        nff              %
        ngw              %
        nnn              %
        nnn_max          %
        np               %
        nt               %
        num              %
        num_nodes_Tz_free %
        number_modes     %
        num_columns      %
        omega            %
        omegad           %
        omegan           % Angular natural frequencies
        part             % participation factor
        pomn2            %
        pvtrans          %
        rdtrans          %
        relative_disp    %
        relative_vel     % 
        response_dof     %
        rho              % Mass density
        rs               %
        s1               % modal frequency 1
        s2               % modal frequency 2
        s3               % modal frequency 3
        s4               % modal frequency 4
        s5               % modal frequency 5
        s6               % modal frequency 6   
        scale            %
        shear_force      % 
        sq_mass          % sqrt of mass
        strans           % 
        stress           %
        tdof             % translational dof
        titlea           %
        x                % x length along beam for mode shap        
        xx               % x length along beam for mode shapes
        xxr              % 
        y                % Mode shape        
        
            
        relative_velocity     %
        relative_displacement %   
        acc_response_psd %
	    rv_response_psd  %
        rd_response_psd  %
        acc_response_trans    %
        rd_response_trans     %        
        moment_response_psd   %            
        shear_response_psd    %
        stress_response_psd   %
        stress_response_trans %        
         
        
        pModeShapes %
        pMST     %
        pdof     %
        
        Mww  %
        Mwd  %
        Kww  %

        acc_trans    %
        pv_trans     %
        rd_trans     %
        stress_trans %      

        constraint_matrix %
      
    end
    
    methods (Access = private)

        function ChoosePlots(app)
            Items = strtrim(app.PlotListBox.Items);
            im = strtrim(app.PlotListBox.Value);
            app.chplot = find(strcmp(Items, im));

            app.ResponseLocationfromLeftEndEditField.Visible='off';
            app.ResponseLocationfromLeftEndEditFieldLabel.Visible='off';
            app.ScriptwillfindnearestnodelocationLabel.Visible='off';
            app.inchLabel.Visible='off';

            if(app.chplot>=2)
                app.ResponseLocationfromLeftEndEditField.Visible='on';
                app.ResponseLocationfromLeftEndEditFieldLabel.Visible='on';
                app.ScriptwillfindnearestnodelocationLabel.Visible='on';  
                app.inchLabel.Visible='on';
            end    
            
        end           

        function ChangeUnits(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

             if(app.iu==1)
                app.LengthinEditField_2Label.Text='Length (in)';
                app.inchLabel.Text='inch';
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            else
                app.LengthinEditField_2Label.Text='Length (m)'; 
                app.inchLabel.Text='m';   
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (m/sec)'; 'Wavenumber (rad/m)'};
            end    

            if(app.iu==1)
                app.scale=386;
                app.LU='in';
            else
                app.scale=9.81;
                app.LU='m';
            end    

            
            Clearfn(app);
            ChangeCrossSection(app);  
            ChangeMaterials(app);           
        end

        function Clearfn(app)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';            
            app.UITable_fn.Visible='off';
            app.PlotModeShapeButton.Visible='off';    
            app.fn=0;
        end   

        function pre_trans(app)   

            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    

            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end            

            app.fig_num=1;

            app.num_nodes_Tz_free=round(app.dof/2);
            
            % app.dm

            num_Tz=length(app.TZ_tracking_array);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%  need to cleanup
            
            sz=size(app.Mww);
            app.nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.omegan=2*pi*app.fn;

            omn2=app.omegan.^2;

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            oct = 2^(1/48);
            f_min = app.fmin;
            f_max = app.fmax;
            
            k = 0:floor(log(f_max/f_min) / log(oct));
            f = f_min * oct.^k;
            f(f > f_max) = f_max;
            
            app.freq=fix_size(f);
            app.np=length(f);
            
            %
             
            app.omega=2*pi*f;
            om2=app.omega.^2;
            
            %
            [~,jnear]=min(abs(app.omegan(1)-app.omega));
            
            N=zeros(app.nff,1);
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            num_modes_include=str2num(app.NumberofModestoIncludeListBox.Value);
            
            iaa=1;
            ibb=num_modes_include;
                  
            if(app.nff> num_modes_include)
                app.nff=num_modes_include;
            end    
            
            %
            app.y=ones(app.nem,1);
            
            try
                A=-app.MST*app.Mwd*app.y;
            catch
                app.nem
                size(app.MST)
                size(app.Mwd)
                size(app.y)
                warndlg('Error:  ref 1')
                return;
            end
            %
            app.acc_trans=zeros(app.np,num_Tz);
            app.pv_trans=zeros(app.np,num_Tz);
            app.rd_trans=zeros(app.np,num_Tz);
            app.stress_trans=zeros(app.np,num_Tz);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             damp=app.dm;
            
            fprintf('\n\n  app.np=%d  iaa=%d  ibb=%d \n\n',app.np,iaa,ibb);

            for k=1:app.np  % for each excitation frequency
                
                for i=iaa:ibb  % dof

                    N(i)=A(i)/(omn2(i)-om2(k) + (1i)*2*damp(i)*app.omegan(i)*app.omega(k));
                end
                
                Ud=zeros(app.nem,1); 
                for i=1:app.nem  % convert acceleration input to displacement
                    Ud(i)=1/(-om2(k));
                end
            %
                Uw=app.ModeShapes*N;   
            
                Udw=[Ud; Uw];
            %
                U=app.TT*Udw;    
                
                nu=length(U);
                
                if(k==1)
            %        fprintf(' nu=%d num_elem=%d  num_nodes_Tz_free=%d\n',nu,num_elem,num_nodes_Tz_free);
            %        ngw
                end
                
                Ur=zeros(nu,1);
                
                Ur(app.ngw(1:nu)) = U(1:nu);

                num_elem=app.ne;
                
                if(k==1)
                    num_nodes=num_elem+1;
            %       total_dof=num_nodes*2;
                
                    R_tracking_array=zeros(num_nodes,1);
            
            %        rot_dof;  
                    num_Ur=length(Ur);
                
                    rot_constraint_matrix=app.constraint_matrix;
            
                    disp('app.contraint_matrix')
                    app.constraint_matrix
                
                    length(app.constraint_matrix(:,1))
                
                    for i=length(app.constraint_matrix(:,1)):-1:1
                        if(app.constraint_matrix(i,3)==0)
                            rot_constraint_matrix(i,:)=[];
                        end
                    end
                
                    bbb=(1:num_Ur);
                
                    for i=1:num_Tz
                        [Lia,Locb] =ismember(app.TZ_tracking_array,bbb);
                        if(Lia==1)
                            bbb(Locb)=[];
                        end
                    end
                
            %%        fprintf(' num_nodes=%d  num_Ur=%d \n',num_nodes,num_Ur);
                    ccc=rot_constraint_matrix(:,1);
                    
                    kv=1;
                    for jj=1:num_nodes    
                        if(ismember(jj,ccc))
            %                fprintf(' jj=%d  included \n',jj);
                        else    
            %                fprintf(' jj=%d  kv=%d \n',jj,kv);
                            R_tracking_array(jj)=bbb(kv);
                            kv=kv+1;
                        end
                    end
                end

                for jk=1:num_Tz
                    node=jk;    
                    ij=app.TZ_tracking_array(node);
                    app.acc_trans(k,jk)=om2(k)*abs(Ur(ij));
                    app.rd_trans(k,jk)=abs(Ur(ij)-Ud(1));
                end
                
                for nk=1:num_nodes-1
                
                    left_node=nk;
                    right_node=nk+1;
            
                    LL=abs(app.xx(left_node)-app.xx(right_node));
                    
                    [B]=beam_stress_B(0,LL);
                    
                    TL=app.TZ_tracking_array(left_node);
                    TR=app.TZ_tracking_array(right_node);
                    
                    URL=0;
                    URR=0;
                    
                    if(R_tracking_array(left_node)~=0)
                        RL=R_tracking_array(left_node);
                        URL=Ur(RL);
                    end
                    if(R_tracking_array(right_node)~=0)
                        RR=R_tracking_array(right_node);
                        URR=Ur(RR);            
                    end
                     
                     d=transpose([Ur(TL) URL Ur(TR) URR]);
            
            %       if(k==1)
            %           fprintf('nk=%d  %d %d %d %d   \n',nk,TL,RL,TR,RR);
            %            fprintf('  %8.4g  %8.4g  %8.4g  %8.4g \n\n',d(1),d(2),d(3),d(4));
            %            B
            %        end
            
                        app.stress_trans(k,nk)=abs(app.cna*app.E*B*d); 
                        
                       QQQ=1; 
                       if(k==jnear  && QQQ==1)
            %                 dzL=Ur(TL);
            %                 ryL=URL; 
            %                 dzR=Ur(TR); 
            %                 ryR=URR; 
                             app.s1=app.stress_trans(k,nk);
            %                 fprintf('\n\n nk=%d \n\n',nk);
            %                 fprintf(' cna=%g  E=%7.3g  s1=%8.4g \n',cna,E,s1*386);
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(1)*dzL),imag(B(1)*dzL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(2)*ryL),imag(B(2)*ryL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(3)*dzR),imag(B(3)*dzR));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(4)*ryR),imag(B(4)*ryR));   
            %                 cna*E*B*d
            %                transpose([Ur(TL) URL Ur(TR) URR]);
                        end
                    
                end
                
            % stress at right end  
                app.stress_trans(k,end)=app.stress_trans(k,end-1);
                
            end

            app.pv_trans = app.rd_trans .* (2 * pi * app.freq);

            md=5;
            x_label='Frequency (Hz)';

            %%%%%%%%%%%%%%%%

            [~,column_a,max_a]=find_max_2d_array(app.acc_trans);
            app.ppp_a=[app.freq app.acc_trans(:,column_a)];

            app.pv_trans=app.scale*app.pv_trans;
            [~,column_pv,max_pv]=find_max_2d_array(app.pv_trans);
            app.ppp_pv=[app.freq app.pv_trans(:,column_pv)];

            app.rd_trans=app.scale*app.rd_trans;
            [~,column_rd,max_rd]=find_max_2d_array(app.rd_trans);
            app.ppp_rd=[app.freq app.rd_trans(:,column_rd)];

            app.stress_trans=app.scale*app.stress_trans;
            [~,column_st,max_st]=find_max_2d_array(app.stress_trans);
            app.ppp_st=[app.freq app.stress_trans(:,column_st)];

            %%%%%%%%%%%%%%%%%

            fprintf('\n Max Values \n');

            fprintf('          Acceleration Trans = %8.4g G/G \n',max_a);

            if(app.iu==1)
                fprintf('       Pseudo Velocity Trans = %8.4g ips/G \n',max_pv);
                fprintf(' Relative Displacement Trans = %8.4g in/G \n',max_rd);
                fprintf('                Stress Trans = %8.4g psi/G \n',max_st);  
            else
                fprintf('       Pseudo Velocity Trans = %8.4g m/sec/G \n',max_pv);
                fprintf(' Relative Displacement Trans = %8.4g m/G \n',max_rd);
                fprintf('                Stress Trans = %8.4g Pa/G \n',max_st); 
            end

            K=max_st/(max_pv*sqrt(app.E*app.rho));  

            if(app.itype==1)
                fprintf('\n Stress-Velocity K = %8.4g \n',K);      
            end

            if(app.chplot>=2 && app.itype==1)

                xr=str2num(app.ResponseLocationfromLeftEndEditField.Value);

                [~,app.node_near]=min(abs(app.xx-xr));

                %%%%%%%%%%%%%%%%%
    
                y_label='Trans (G/G)';
                t_string=sprintf('Acceleration Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_a=[app.freq app.acc_trans(:,app.node_near)];

                if(min(app.qqq_a(:,2))>0)   
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_a,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (ips/G)';
                else
                    y_label='Trans (m/sec/G)';                    
                end    

                t_string=sprintf('Pseudo Velocity Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_pv=[app.freq app.pv_trans(:,app.node_near)];

                if(min(app.qqq_pv(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_pv,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (in/G)';
                else
                    y_label='Trans (m/G)';                    
                end   

                t_string=sprintf('Rel Disp Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_rd=[app.freq app.rd_trans(:,app.node_near)];

                if(app.qqq_rd(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_rd,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (psi/G)';
                else
                    y_label='Trans (Pa/G)';                    
                end   

                t_string=sprintf('Stress Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_st=[app.freq app.stress_trans(:,app.node_near)];

                if(app.qqq_st(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_st,app.fmin,app.fmax,md);            
                end

            end    

            if(app.chplot~=2 && app.itype==1)
            
                %%%%%%%%%%%%%%%%%
    
                y_label='Trans (G/G)';
                t_string=sprintf('Maximum Acceleration Transmissibility at x=%7.3g %s',app.xx(column_a),app.LU);
    
                if(min(app.ppp_a(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_a,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (ips/G)';
                else
                    y_label='Trans (m/sec/G)';                    
                end   

                t_string=sprintf('Maximum Pseudo Velocity Transmissibility at x=%7.3g %s',app.xx(column_pv),app.LU);
    
                if(min(app.ppp_pv(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_pv,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (in/G)';
                else
                    y_label='Trans (m/G)';                    
                end   


                t_string=sprintf('Max Rel Disp Transmissibility at x=%7.3g %s',app.xx(column_rd),app.LU);
    
                if(app.ppp_rd(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_rd,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
                
                if(app.iu==1)
                    y_label='Trans (psi/G)';
                else
                    y_label='Trans (Pa/G)';                    
                end   


                t_string=sprintf('Max Stress Transmissibility at x=%7.3g %s',app.xx(column_st),app.LU);
    
                if(app.ppp_st(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_st,app.fmin,app.fmax,md);            
                end

            end

            %%%%%%%%%%%%%%%%%            

            assignin('base', 'max_accel_trans', app.ppp_a);
            assignin('base', 'max_pv_trans', app.ppp_pv);
            assignin('base', 'max_rd_trans', app.ppp_rd);
            assignin('base', 'max_stress_trans', app.ppp_st);

        end


       function update_srs(app)

            CloseFigures(app);
            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox_srs.Items=unique(sort(cell_array)); 
            try
                app.SelectInputArrayListBox_srs.Value=app.SelectInputArrayListBox_srs.Items{1};
            catch
            end
            try
                app.listbox_method_srs.Value=app.listbox_method_srs.Items{1};
            catch
            end
        end                    

       function update_th(app)
            CloseFigures(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox.Value=app.SelectInputArrayListBox.Items{1};
            catch
            end           
            try
                app.listbox_method.Value=app.listbox_method.Items{1};
            catch
            end
        end

        function PreProcess(app)
            CloseFigures(app);
            
                    
            app.kflag=0;
 
            if(app.LBC==1 && app.RBC==1)
                app.kflag=1;
            end           
            if(app.LBC==1 && app.RBC==2)
                app.kflag=1;
            end         
            if(app.LBC==1 && app.RBC==3)
                app.kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                app.kflag=1;
            end

            if(app.kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end        
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end     
            
        end    

        function update_psd(app)
            CloseFigures(app);
            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end          

        function beam_FEA_base_arbitrary(app)

            disp('Arb')

            clear app.na;
            clear app.acc;
            clear app.ddd;
            clear app.nt;
            clear app.acceleration;
            clear app.relative_disp;          
            clear app.stress;

            fig_num=1;

            NT=length(app.THM(:,1));
            app.nt=NT;

            EA=app.THM;
            
            dur=EA(NT,1)-EA(1,1);
            app.dt=dur/(NT-1);
            
            sr=1/app.dt;
            
            fprintf(' NT=%d  dur=%8.4g sec  app.dt=%8.4g sec \n',NT,dur,app.dt);  

            fprintf('\n number of modes included =%d \n',app.number_modes);
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integrating acceleration ');
            
            ndof=app.pdof;
            
            f=app.THM(:,2);
            
            if(app.BC==1)
                fin=f;
            else
                fin=[ f f]; 
            end
            
            [dvelox]=integrate_function(f,app.dt);
            [app.ddisp]=integrate_function(dvelox,app.dt);
            %
            %  Modal Transient
            %
            clear app.omegad;
            app.omegad=zeros(app.nff,1);

            for i=1:min([ length(app.dm) length(app.omegan) ])
                app.omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
            end
            
            noo=app.number_modes;
        
            for i=1:noo
                
                if(sr<10*app.fn(i))
                   app.number_modes=i-1;
                   fprintf('\n Number of included modes = %d due to input data sample rate limitation. \n',app.number_modes);         
                   break; 
                end
                
            end

            fprintf('\n sample rate = %8.4g samples/sec \n',sr);

            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:app.number_modes 
               fprintf(' %d.  %8.3g  %6.3g \n',i,app.fn(i),app.dm(i)); 
            end
            
            Q=app.ModeShapes(:,1:app.number_modes);
            QT=Q';

            ff=zeros(app.nem,1);

            progressbar;

            progressbar(0.05);

            QTapp.Mwd=QT*app.Mwd;
            
            for i=1:length(f)
                ff(1:app.nem,i)=f(i);
            end

            progressbar(0.1);
            
            disp(' ');
            disp(' Calculating FP ');
        
            FP=-QTapp.Mwd*ff;
            
            disp(' Completed FP ')    

            [a1,a2,df1,df2,df3,~,~,~,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(app.number_modes,app.omegan,app.dm,app.dt);

            nx=zeros(app.nt,app.number_modes);
            app.na=zeros(app.nt,app.number_modes);
        
            for j=1:app.number_modes
            %
                amodal=FP(j,:);
            %
            %  displacement
            %
                d_forward=[   df1(j),  df2(j), df3(j) ];
                d_back   =[     1, -a1(j), -a2(j) ];
                d_resp=filter(d_forward,d_back,amodal);
            %       
            %  acceleration
            %   
                a_forward=[   af1(j),  af2(j), af3(j) ];
                a_back   =[     1, -a1(j), -a2(j) ]; 
                a_resp=filter(a_forward,a_back,amodal);
            %
                nx(:,j)=d_resp;  % displacement
                app.na(:,j)=a_resp;  % acceleration  
            %
            end

            progressbar(0.25);

            %
            clear amodal;
            clear FP;
            clear d_resp;
            clear a_resp;
            
            disp(' Calculate a');

            fprintf('\n app.number_modes=%d \n',app.number_modes);
            fprintf('app.nt=%d \n',app.nt);
            fprintf('ndof=%d \n',ndof);
            fprintf('app.nem=%d \n',app.nem);            
            
            szQ=size(Q);

            d=zeros(szQ(1),app.nt);
            a=zeros(szQ(1),app.nt);

            try
                d(:,:)=(Q*(nx(:,:))');
            catch
                size(d)
                size(Q)
                size(nx)
                warndlg('d failure')
                return;
            end
            clear nx;

            progressbar(0.3);
            
            disp(' Calculate d');
            a(:,:)=(Q*(app.na(:,:))'); 
            clear na;

            progressbar(0.4);
 
            %%%%

            d=d';
            a=a';
            
            fin=fix_size(fin);
            
            disp(' Transformation a  ');
            
            Adw=[fin a];
            
            sz=size(Adw);
            mqq=sz(2);
            
            A=zeros(app.nt,mqq);
            D=zeros(app.nt,mqq);

            A(:,:)=(app.TT*Adw(:,:)')';
            clear Adw;

            progressbar(0.5);
            
            disp(' Transformation d ');

            if(app.BC==1)
                Ddw=[app.ddisp d];
            else
                Ddw=[app.ddisp app.ddisp d];    
            end    
            
            D(:,:)=(app.TT*Ddw(:,:)')';
            clear Ddw;

            progressbar(0.6);

            app.acc=zeros(app.nt,mqq);
            app.ddd=zeros(app.nt,mqq);
            
            disp(' Sort  A'); 
            app.acc(:,app.ngw(:))=A(:,:);
            clear A;
            
            disp(' Sort  D'); 
            app.ddd(:,app.ngw(:))=D(:,:);
            clear D;

            progressbar(0.7);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Generate complete vectors ');
            
            zz=zeros(app.nt,1);
            
            sz=size(app.acc);
            nmn=sz(2);
            
            if(app.BC==1)  % 1  Fixed-Free
                app.acc=[ app.acc(:,1) zz app.acc(:,2:nmn)];
                app.ddd=[ app.ddisp zz app.ddd(:,2:nmn)];
            end  
            if(app.BC==2)  % 2  Pinned-Pinned
                % arrays already correct
            end        
            if(app.BC==3)  % 3  Fixed-Fixed
                app.acc=[ app.acc(:,1) zz app.acc(:,2:nmn) zz];
                app.ddd=[ app.ddisp zz app.ddd(:,2:nmn) zz];            
            end  
            if(app.BC==4)  % 4  Fixed-Pinned
                app.acc=[ app.acc(:,1) zz app.acc(:,2:nmn)];
                app.ddd=[ app.ddisp zz app.ddd(:,2:nmn)];             
            end             

            if(app.iu==1)
               app.ddd=app.ddd*386;
               app.ddisp=app.ddisp*386;
            else
               app.ddd=app.ddd*9.81;
               app.ddisp=app.ddisp*9.81;
            end    
            
            progressbar(0.75);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integration ');

            size(app.acc)
            app.response_dof

            aaa=app.acc(:,app.response_dof);
            rrr=app.ddd(:,app.response_dof)-app.ddisp;

            app.acceleration=[app.THM(:,1),aaa];
            app.relative_disp=[app.THM(:,1),rrr];

            progressbar(0.8);
     
            Stress_th(app);

            progressbar(0.9);

            app.stress=app.HM_stress_response/1000;

            app.stress=fix_size(app.stress);

            try
                app.bending_stress=[app.THM(:,1),app.stress];
            catch
                size(app.THM(:,1))
                size(app.stress)
            end
            
            bstress=app.stress;

            amin=min(aaa);
            amax=max(aaa);         
            arms=std(aaa);   

            srdmin=min(rrr);
            srdmax=max(rrr);         
            srd=std(rrr);  

            stmin=min(bstress);
            stmax=max(bstress);         
            stss=std(bstress);              

            ss1=sprintf('Accel (G) \n  max=%7.3g  min=%7.3g  std dev=%7.3g\n\n',amax,amin,arms);
            ss2=sprintf('Rel Disp (in) \n  max=%6.2g  min=%6.2g  std dev=%6.2g\n\n',srdmax,srdmin,srd);
            ss3=sprintf('Stress (ksi) \n  max=%7.3g  min=%7.3g  std dev=%7.3g\n\n',stmax,stmin,stss);

            app.ResultsTextArea.Value=sprintf('%s %s %s',ss1,ss2,ss3);

            progressbar(1);

            string_th{1}='Acceleration';
            string_th{2}='Relative Disp';
            string_th{3}='Stress';           

            app.OutputFunctionListBox.Items=string_th; 
            app.xxr=strtrim(app.xxr);

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),aaa);
            grid on
            ylabel('Accel (G)');
            xlabel('Time (sec)');
            legend('Input','Response');
            t_string=sprintf('Acceleration  x=%s inch',app.xxr);
            title(t_string);

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),aaa);
            grid on
            ylabel('Accel (G)');
            xlabel('Time (sec)');
            t_string=sprintf('Acceleration Response  x=%s inch',app.xxr);
            title(t_string); 

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),rrr);
            grid on
            ylabel('Rel Disp (in)');
            xlabel('Time (sec)');
            t_string=sprintf('Relative Displacement  x=%s inch',app.xxr);
            title(t_string);     

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),bstress);
            grid on
            ylabel('Stress (ksi)');
            xlabel('Time (sec)');
            t_string=sprintf('Bending Stress  x=%s inch',app.xxr);
            title(t_string);            

        end    

        function Stress(app)

            clear app.HM_stress;

            app.HM_stress=zeros(app.np,1);
                        
            app.nnn_max=app.ne+1; 

            pL=mean(app.dx);

            px=0;
            [B0]=beam_stress_B(px,pL);

            if(app.nnn>=1 && app.nnn<=(app.nnn_max-1))

                L1=app.response_dof;
                L2=app.response_dof+1;    
                L3=app.response_dof+2;
                L4=app.response_dof+3; 

                for j=1:app.np
                    Y(1)=app.ddd(j,L1);
                    Y(2)=app.ddd(j,L2);
                    Y(3)=app.ddd(j,L3);
                    Y(4)=app.ddd(j,L4);
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                    app.HM_stress(j)=abs(app.cna*app.E*B0*d); 
                end
            end
    
            if(app.nnn==app.nnn_max && app.BC~=1)
                px=pL;
                [B]=beam_stress_B(px,pL);            
                    
                for j=1:app.np          
                    Y(1)=app.ddd(j,end-3);
                    Y(2)=app.ddd(j,end-2);
                    Y(3)=app.ddd(j,end-1);
                    Y(4)=app.ddd(j,end);
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                    app.HM_stress(j)=abs(app.cna*app.E*B*d);
                end                   
            end

        end    

        function Stress_th(app)

            ntt=app.nt;
            app.HM_stress_response=zeros(ntt,1);

            app.adisp=app.ddd;

            app.nnn_max=app.ne+1; 
            pL=mean(app.dx);
            px=0;
            [B0]=beam_stress_B(px,pL);
            
            sz=size(app.adisp);

            for i=1:sz(1)
                for j=1:2:sz(2)
                    app.adisp(i,j)=app.adisp(i,j)-app.ddd(i);
                end
            end    

            if(app.nnn<=app.nnn_max-1)

                L1=app.response_dof;
                L2=app.response_dof+1;    
                L3=app.response_dof+2;
                L4=app.response_dof+3; 

                for j=1:ntt        
                    Y(1)=app.adisp(j,L1);
                    Y(2)=app.adisp(j,L2);
                    Y(3)=app.adisp(j,L3);
                    Y(4)=app.adisp(j,L4);
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                    app.HM_stress_response(j)=(app.cna*app.E*B0*d);
                end
            end
         
            if(app.nnn==app.nnn_max)
                px=pL;
                [B]=beam_stress_B(px,pL);   

                if(app.BC~=1)

                    for j=1:ntt        
                        Y(1)=app.adisp(j,end-3);
                        Y(2)=app.adisp(j,end-2);
                        Y(3)=app.adisp(j,end-1);
                        Y(4)=app.adisp(j,end);
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B*d);
                    end
                end
            end
            
        end    

        function ResponseX(app)

            disp('X')

            app.number_modes=str2num(app.NumberofModestoIncludeListBox.Value);

            Lfn=length(app.fn);

            if(app.number_modes>Lfn)
                app.number_modes=Lfn;
            end    

            try
                app.xxr=app.ResponseLocationListBox.Value;  
                app.xxr=strtrim(app.xxr);
            catch
                warndlg('app.xxr failed');
                app.kflag=999;
                return; 
            end

            try
                BeamBaseImage(app);
                Items = char(app.ResponseLocationListBox.Items);
                im=app.ResponseLocationListBox.Value;
                [app.nnn]=find_index(Items,im);          

                app.xxr=im;
                app.response_dof=2*app.nnn-1;
            catch
                fprintf(' app.nnn=%d  app.ne=%d \n',app.nnn,app.ne);
                app.tdof
                warndlg('app.tdof error');
                return;
            end         


            app.kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                app.kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                app.kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                app.kflag=1;
            end
            if(app.LBC==1 && app.RBC==2) % fixed-pinned
                app.kflag=1;
            end

            if(app.iu==1)
               app.rs=386; 
            else
               app.rs=9.81; 
            end
        end

        function PostOff(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.OutputFunctionListBox.Visible='off';
            app.OutputFunctionListBoxLabel.Visible='off';
            app.SaveButton.Visible='off';
            app.OutputArrayNameEditField.Visible='off';
            app.OutputArrayNameEditFieldLabel.Visible='off';
        end   

        function PostOn(app)
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';       
            app.OutputFunctionListBox.Visible='on';
            app.OutputFunctionListBoxLabel.Visible='on';
            app.SaveButton.Visible='on';
            app.OutputArrayNameEditField.Visible='on';
            app.OutputArrayNameEditFieldLabel.Visible='on';
        end    

        function CloseFigures(app)

            PostOff(app);
            
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
        
        function ChangeMaterials(app)
            Clearfn(app);
            
            app.E=0;
            app.rho=0;            

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';   
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (in)';
                app.PointMasslbmEditFieldLabel.Text='Point Mass (lbm)';
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (m)';
                app.PointMasslbmEditFieldLabel.Text='Point Mass (kg)';              
            end    

            app.ElasticModuluspsiEditField.Value=sprintf('%8.4g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%8.4g',app.rho);        
            
        end
        
        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end
        
        function ChangeCrossSection(app)
            Clearfn(app);

            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Width (in)';
                    app.EditField_2Label.Text='Thickness (in)';  
                else
                    app.EditField_1Label.Text='Width (m)';
                    app.EditField_2Label.Text='Thickness (m)';  
                end    
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';               
            end
            if(contains(n,'Pipe'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Outer Diameter (in)';
                    app.EditField_2Label.Text='Wall Thickness (in)';
                else
                    app.EditField_1Label.Text='Outer Diameter (m)';
                    app.EditField_2Label.Text='Wall Thickness (m)';
                end
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 if(app.iu==1)
                    app.EditField_1Label.Text='Diameter (in)';
                 else
                    app.EditField_1Label.Text='Diameter (m)';                     
                 end
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Base Width (in)';
                    app.EditField_2Label.Text='Total Height (in)';
                    app.EditField_3Label.Text='Flange Thickness (in)';     
                    app.EditField_4Label.Text='Web Thickness (in)';
                else
                    app.EditField_1Label.Text='Base Width (m)';
                    app.EditField_2Label.Text='Total Height (m)';
                    app.EditField_3Label.Text='Flange Thickness (m)';     
                    app.EditField_4Label.Text='Web Thickness (m)';
                end

                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Area (in^2)';
                    app.EditField_2Label.Text='Area moment Inertia (in^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                else
                    app.EditField_1Label.Text='Area (m^2)';
                    app.EditField_2Label.Text='Area moment Inertia (m^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (m)';
                end

                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';

        end
        
        function BeamBaseImage(app)
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;  
            
            num_nodes=app.ne+1;
       
            for i=1:num_nodes
                app.tdof(i)=(2*i-1);
            end    
                        
            if(contains(nL,'Fixed') && contains(nR,'Free'))                
                app.ImageBeamBase.ImageSource='beam_bending_base.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=1;
            end
            if(contains(nL,'Pinned') && contains(nR,'Pinned'))              
                app.ImageBeamBase.ImageSource='beam_bending_base_ss.jpg'; 
                app.BC=2;
            end   
            if(contains(nL,'Fixed') && contains(nR,'Fixed'))             
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=3;
            end
            
            if(contains(nL,'Fixed') && contains(nR,'Pinned'))                 
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=4;
            end    

            try
                ss1=sprintf('Acceleration Transmissibilty');
                ss2=sprintf('Relative Displacement Transmissibility');
                ss3=sprintf('Bending Stress');                   
                app.OutputFunctionListBox_Trans.Items={ss1,ss2,ss3};
            catch   
            end

            try
                string_th{1}=sprintf('Acceleration Transmissibilty');
                string_th{2}=sprintf('Acceleration PSD');
                string_th{3}=sprintf('Relative Displacement Transmissibility');  
                string_th{4}=sprintf('Relative Displacement PSD');
                string_th{5}=sprintf('Bending Stress Transmissibilty');
                string_th{6}=sprintf('Bending Stress PSD');                
                app.OutputFunctionListBox_PSD.Items=string_th;
            catch       
            end
            
            clear string_th;
            
            try
                string_th{1}=sprintf('Absolute Acceleration');
                string_th{2}=sprintf('Relative Displacement');
                string_th{3}=sprintf('Bending Stress');                
                app.OutputFunctionListBox_arbitrary.Items=string_th;
                app.OutputFunctionListBox_HS.Items=string_th;
            catch       
            end             
            
        end
        
        function PointMass(app)
        
            n=str2num(app.NumberofPointMassesListBox.Value);
        
            app.PointMasslbmEditField.Visible='off';
            app.PointMassLocationfromLeftEndinEditField.Visible='off';
            app.PointMasslbmEditFieldLabel.Visible='off';
            app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='off';
            
            if(n==1)
                app.PointMasslbmEditField.Visible='on';
                app.PointMassLocationfromLeftEndinEditField.Visible='on';
                app.PointMasslbmEditFieldLabel.Visible='on';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='on';                 
            end
            
        end

        function BeamTransCore(app)

            N=zeros(app.nff,app.np);

            app.adisp=zeros(app.np,app.num);
            app.acc=zeros(app.np,app.num);
            rel_disp=zeros(app.np,app.num);

            om2=app.omega.^2;

            app.y=ones(app.nem,1);
           
            progressbar;

            A=-app.pMST*app.Mwd*app.y;

            app.number_modes=str2num(app.NumberofModestoIncludeListBox.Value);
            nu=app.number_modes;
         
            app.num_columns=min([nu length(app.fn)]); 
            
            fprintf('\n number of modes included =%d \n',app.num_columns);
                
            nk=min([ length(A) length(app.dm) app.num_columns]);

            total=nk+app.num+app.np;
            kv=0;

            %
            progressbar
            for i=1:nk  % number of included modes
                progressbar(kv/total);
                kv=kv+1;
                for k=1:app.np
                    N(i,k)=A(i)/(app.pomn2(i)-om2(k) + (1i)*2*app.dm(i)*app.omegan(i)*app.omega(k));
                end
            end
 
            %
            Ud=zeros(app.nem,app.np); 
            for i=1:app.np  % convert acceleration input to displacement
                progressbar(kv/total);
                kv=kv+1;
                Ud(:,i)=1/(-om2(i));
            end

            %
            % disp('ModeShapes');
            size(app.pModeShapes);
                
            Uw=app.pModeShapes*N;

            %
            Udw=[Ud; Uw];

            %
            U=app.TT*Udw;
            %
            %   Fix order
            %
            for i=1:app.num
                progressbar(kv/total);
                kv=kv+1;
                for j=1:app.np
                    app.adisp(j,app.ngw(i))=U(i,j);
                    app.acc(j,app.ngw(i))=om2(j)*abs(U(i,j));
                    rel_disp(j,app.ngw(i))=U(i,j)-Ud(1,j);
                end        
            end
            progressbar(1);

            zz=zeros(app.np,1);     

            if(app.BC==1)  % 1  Fixed-Free
                app.acc=[ app.acc(:,1) zz app.acc(:,2:end)];
                app.ddd=[ app.adisp(:,1) zz app.adisp(:,2:end)];
                rel_disp=[ rel_disp(:,1) zz rel_disp(:,2:end)];
            end  
            if(app.BC==2)  % 2  Pinned-Pinned
                % arrays already correct
            end        
            if(app.BC==3)  % 3  Fixed-Fixed
                app.acc=[ app.acc(:,1) zz app.acc(:,2:end) zz];
                app.ddd=[ app.adisp(:,1) zz app.adisp(:,2:end) zz]; 
                rel_disp=[ rel_disp(:,1) zz rel_disp(:,2:end) zz];
            end  
            if(app.BC==4)  % 4  Fixed-Pinned
                app.acc=[ app.acc(:,1) zz app.acc(:,2:end)];
                app.ddd=[ app.adisp(:,1) zz app.adisp(:,2:end)];    
                rel_disp=[ rel_disp(:,1) zz rel_disp(:,2:end)];  
            end                        

            app.HM_accel=zeros(app.np,1);
            app.HM_rd=zeros(app.np,1);
           
            for j=1:app.np      
                app.HM_accel(j)  =app.acc(j,app.response_dof);   
                app.HM_rd(j)     =rel_disp(j,app.response_dof);            
            end

            if(app.iu==1)
               app.ddd=app.ddd*386;
               app.adisp=app.adisp*386;
               app.HM_rd=app.HM_rd*386;
            else
               app.ddd=app.ddd*9.81;
               app.adisp=app.adisp*9.81;
               app.HM_rd=app.HM_rd*9.81;
            end  

            app.np=length(app.freq);
            Stress(app);

        end     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeUnits(app);
            ChoosePlots(app);
            app.PointMasslbmEditField.Value=sprintf('0');
            app.PointMassLocationfromLeftEndinEditField.Value=sprintf('0');
            update_psd(app);
            update_th(app);
            update_srs(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            ChangeCrossSection(app);
            BeamBaseImage(app);
            PointMass(app);
            app.iu=1;
            app.titlea='999';
            app.UniformNonstructuralMasslbmEditField.Value=sprintf('0');
            
            for t = 1:numel(app.TabGroup.Children)
                app.TabGroup.Children(t).Tooltip = app.TabGroup.Children(t).Title;
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ChangeMaterials(app);
            Clearfn(app);
        end

        % Callback function
        function CalculateBaseInputTransButtonPushed(app, event)
    
        end

        % Callback function
        function CalculateBaseInputSineButtonPushed(app, event)
                           
        end

        % Callback function
        function CalculateAppliedForceLengthFRFButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end            
           
        end

        % Button pushed function: 
        % CalculateNaturalFrequenciesPartitionButton
        function CalculateNaturalFrequenciesPartitionButtonPushed(app, event)
            
            CloseFigures(app);
        
            fprintf('\n * * * * * * * * * \n\n');
        
            app.L=str2num(app.LengthinEditField_2.Value);
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);
       
            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
            end

            app.rho=app.rho/386;
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=3;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=3;                
            end    
            
            if((app.LBC==2 && app.RBC==3) || (app.LBC==3 && app.RBC==2)) % pinned-free
                warndlg('case unavailable');
                return;
            end
    
            %%%%%%%%%%%%%%%%%%%%%
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=str2num(app.EditField_1.Value);
                thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=str2num(app.EditField_1.Value);
                wall_thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=str2num(app.EditField_1.Value);
                [area,app.MOI,app.cna]=cylinder_geometry(diameter);     
            end
            if(contains(n,'I-Beam'))
                B=str2num(app.EditField_1.Value);
                H=str2num(app.EditField_2.Value);
                Tf=str2num(app.EditField_3.Value);     
                Tw=str2num(app.EditField_4.Value);  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=str2num(app.EditField_1.Value);
                app.MOI=str2num(app.EditField_2.Value);
                app.cna=str2num(app.EditField_3.Value);       
            end
            
            nsm=str2num(app.UniformNonstructuralMasslbmEditField.Value);
            
            if(app.iu==1)
                nsm=nsm/386;
            end
            
            rho_mpL=app.rho*area + nsm/app.L;   % mass per unit length
            mass=rho_mpL*app.L;
            app.sq_mass=sqrt(mass);

            fprintf('\n area=%8.4g in^2  \t mass=%8.4g lbf sec^2/in \t MOI=%8.4g in^4 \n\n',area,mass,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%
            
            nsn=str2num(app.NumberofElementsListBox.Value);
            
            
            if(nsn==1 && app.LBC==1 && app.RBC==1)
                warndlg('At least 2 elements requried for fixed-fixed');
                return;
            end    
            
            app.ne=nsn;

            deltax=app.L/app.ne;
            
            app.dx=zeros(app.ne,1);
            
            for i=1:app.ne
                app.dx(i)=deltax;
            end
            
            app.xx=zeros((app.ne+1),1);
            
            string_n{1}=sprintf('%8.4g',app.xx(1));
            for i=2:(app.ne+1)
               app.xx(i)=app.xx(i-1)+deltax; 
               string_n{i}=sprintf('%8.4g',app.xx(i));
            end
            
            app.dof=2*app.ne+2;
   
            [klocal] = local_stiffness(app.E,app.MOI,app.ne,app.dx);
            [mlocal] = local_mass(rho_mpL,app.ne,app.dx);
            
            kk=zeros(4,4);
            mm=zeros(4,4);
            
            for i=1:4
                for j=1:4
                    kk(i,j)=klocal(1,i,j);
                    mm(i,j)=mlocal(1,i,j);
                end
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                      
            npm=str2num(app.NumberofPointMassesListBox.Value);
            
            pmloc=zeros(1,1);
            pm=zeros(1,1);
            
            if(npm==1)
                   pm(1)=str2num(app.PointMasslbmEditField.Value);
                pmloc(1)=str2num(app.PointMassLocationfromLeftEndinEditField.Value);
            end
            
            if(app.iu==1) % English
                pm=pm/386;
            end  
                
            [app.global_stiff,app.global_mass,app.global_stiff_unc,app.global_mass_unc,app.dof,app.dof_status,XL,ivector]=...
                         beam_assembly_unc_alt(app.ne,klocal,mlocal,app.LBC,app.RBC,app.dof,npm,pmloc,pm,app.xx);     
             
            if(app.ne==1)
                disp('Unconstrained Global Stiffness Matrix');
                app.global_stiff_unc
                disp('Unconstrained Global Mass Matrix');
                app.global_mass_unc              
                
                disp('Constrained Global Stiffness Matrix');
                app.global_stiff
                disp('Constrained Global Mass Matrix');
                app.global_mass

                disp('app.dof')
                app.dof
                disp('app.dof_status')
                app.dof_status
                disp('ivector')
                ivector
            end
          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(npm>=1)
             
                disp(' ');
                disp(' Point mass ');
             
               for i=1:npm
            
                    if(app.iu==1) % English   
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i)*386);
                    else
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i));       
                    end
               
               end
            end
                                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            
            pms=sum(pm);
                
            tmass=mass+pms;
                
            if(app.iu==1) % English    
                fprintf('\n Total mass = %8.4g lbm \n',tmass*386);
            else
                fprintf('\n Total mass = %8.4g kg \n',tmass);        
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.global_stiff,app.global_mass,2);
            
            [app.part]=beam_fn_pf_emm(app.iu,tmass,app.dof,app.fn,app.global_mass,app.ModeShapes,app.MST,app.dof_status,ivector);  % function
            

            sz=size(app.ModeShapes);
            app.FullModeShapes=zeros(sz(1),sz(2));

            if(app.BC==1)  % 1  Fixed-Free
                app.FullModeShapes=zeros(sz(1)+2,sz(2));
                app.FullModeShapes(3:end,:)=app.ModeShapes;
            end  
            if(app.BC==2)  % 2  Pinned-Pinned
                app.FullModeShapes=zeros(sz(1)+2,sz(2));     
                app.FullModeShapes(2:end-2,:)=app.ModeShapes(1:end-1,:); 
                app.FullModeShapes(end,:)=app.ModeShapes(end,:);          
            end        
            if(app.BC==3)  % 3  Fixed-Fixed
                app.FullModeShapes=zeros(sz(1)+4,sz(2));
                app.FullModeShapes(3:end-2,:)=app.ModeShapes;          
            end  
            if(app.BC==4)  % 4  Fixed-Pinned
                app.FullModeShapes=zeros(sz(1)+3,sz(2));
                app.FullModeShapes(3:end-2,:)=app.ModeShapes(1:end-1,:);  
                app.FullModeShapes(end,:)=app.ModeShapes(end,:);            
            end       

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          
            n=length(app.fn);

            if(n>6)
                n=6;
            end    

            emm=zeros(n,1);
            cp=zeros(n,1);

            rho_mpa=mass/area;

            EI_term = sqrt(app.E*app.MOI/rho_mpa);


            for i=1:n
                app.beta(i)=sqrt(app.omegan(i)/EI_term);
                cp(i)=app.beta(i)*EI_term;
                kw=app.omegan(i)/cp(i);
                emm(i)=app.part(i)^2/mass;
    
                data_s{i,1}=sprintf('%d',i);
                data_s{i,2}=sprintf('%8.4g',app.fn(i));
                data_s{i,3}=sprintf('%7.3g',app.part(i));
                data_s{i,4}=sprintf('%7.3f',emm(i));
                data_s{i,5}=sprintf('%7.4g',cp(i));
                data_s{i,6}=sprintf('%7.3g',kw);
            end

            app.UITable_fn.Data=data_s;
            app.UITable_fn.Visible='on';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_fn,s) % adjust the style

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.fn=fix_size(app.fn);
            
            % dtype=2;
   
            % etype=1;  % enforced acceleration   

            % ea=[1 2]  dof with enforced

            % num total dof
            % nem number of dof with enforced acceleration


            app.cdof=[];
            app.free_dof_array=[];
            app.tracking_array=[];
            app.TZ_tracking_array=[];
            app.constraint_matrix=[];
            app.ea=[];

            if(isempty(app.mass_unc))
                warndlg('Calcuate Natural Frequencies First');
                return;
            end    

            try
                pmass=app.mass_unc;
                pstiff=app.stiff_unc;
            catch
                warndlg('Calculate Natural Frequencies First');
                return;
            end

            % mass
            % stiff
            
            
            sz=size(pmass);
            app.dof=sz(1);
            
            app.tracking_array=zeros(app.dof,1);
            app.free_dof_array=zeros(app.dof,1);
            
            for i=1:app.dof
                app.tracking_array(i)=i;
                app.free_dof_array(i)=i;
            end
            
            app.num_nodes_Tz_free=round(app.dof/2);
            
            app.TZ_tracking_array=zeros(app.num_nodes_Tz_free,1);
            
            for i=1:app.num_nodes_Tz_free
               app.TZ_tracking_array(i)=2*i-1;
            end

            disp('app.tracking_array')
            app.tracking_array

            disp('app.num_nodes_Tz_free')
            app.num_nodes_Tz_free

            disp('app.dof_status')
            app.dof_status

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            
            for i=1:app.num_nodes_Tz_free
                
                if( app.dof_status(2*i-1)==0 || app.dof_status(2*i)==0)
                    app.constraint_matrix(k,:)=[i 0 0];
                    
                    if(app.dof_status(2*i-1)==0)
                        app.constraint_matrix(k,2)=1;
                    end
                    if(app.dof_status(2*i)==0)
                        app.constraint_matrix(k,3)=1;
                    end     
                    
                    k=k+1;
                end    
                    
            end
            
            disp('app.constraint_matrix')
            app.constraint_matrix

            
            k=1;
            
            ibc=1;
            
            n=length(app.constraint_matrix(:,1));
                
            for i=1:n
                
                if(app.constraint_matrix(i,2)==1)
                    app.ea(k)=2*app.constraint_matrix(i,1)-1;
                    k=k+1;
                end
                    
                if(app.constraint_matrix(i,3)==1)
                    app.rot_constraint_matrix(ibc,1)=app.constraint_matrix(i,1);
                    app.rot_constraint_matrix(ibc,2)=0;
                    app.rot_constraint_matrix(ibc,3)=app.constraint_matrix(i,3);
                    ibc=ibc+1;
                end
                        
            end
            
            ibc=ibc-1;
            
            % constraint_matrix
            % ea
            
            if(ibc>=1)
            %    rot_constraint_matrix
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sz=size(app.constraint_matrix);
            
            nc=sz(1);

            fprintf('\n nc=%d \n',nc)
            
            k=1;
            for i=1:nc 
                
                 cmn=app.constraint_matrix(i,1);
                
                 if(app.constraint_matrix(i,2)==1)
                    app.cdof(k)=2*cmn-1;
                    k=k+1;
                 end    
                 if(app.constraint_matrix(i,3)==1)
                    app.cdof(k)=2*cmn;
                    k=k+1;
                 end   
            end
            
            app.cdof=sort(app.cdof,'descend');

            disp('app.cdof')
            app.cdof

            disp('app.free_dof_array')
            app.free_dof_array
            
            for i=1:length(app.cdof)
                fprintf(' i=%d  cdof=%d  free_dof_array=%d\n',i,app.cdof(i),length(app.free_dof_array));
                app.free_dof_array(app.cdof(i))=[];
            end    
            
            % cdof
            % free_dof_array
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            for i=1:ibc 
                 if(app.rot_constraint_matrix(i,3)==1)
                    app.rot_dof(k)=2*app.rot_constraint_matrix(i,1);
                    k=k+1;
                 end
            end
            
            try
                app.rot_dof=sort(app.rot_dof);
                ibc=length(app.rot_dof);  
            catch
                app.rot_dof=0;
                ibc=0;
            end          
  
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
          
            app.ea=fix_size(app.ea);

            sz=size(app.ea);

            app.eac=zeros(sz(1),3);
            
            app.eac(:,1)=app.ea;

            disp('app.ea')
            app.ea
            
            %  rot_dof
            
            if(ibc>=1)  % correct ea matrix
                
                app.rot_dof=sort(app.rot_dof,'descend');
                
                for i=1:ibc
                    for j=1:appnem
                        if(app.ea(j)>app.rot_dof(i))
                            app.ea(j)=app.ea(j)-1;
            %%                out7=sprintf(' %d %d ',ea(j),rot_dof(i));
            %%                disp(out7);
                        end
                    end
                end
                
                app.drot_dof=sort(app.rot_dof,'descend');
                
                for i=1:ibc
                  app.tracking_array(app.drot_dof(i))=[];           
                end   
                
                for i=1:ibc
                    for j=1:app.num_nodes_Tz_free
                        if( app.TZ_tracking_array(j)>app.rot_dof(i) )
                               app.TZ_tracking_array(j)=app.TZ_tracking_array(j)-1;
                        end       
                    end
                end    
                
            end
            
            
            app.eac(:,2)=app.ea;
            
            for i=1:length(app.ea)
                app.eac(i,3)=app.eac(i,2)-app.eac(i,1);
            end
                
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
            
            if(app.nem_ea==0)
                warndlg('No drive dofs');
                return;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(ibc>=1)
                sz=size(app.rot_constraint_matrix);
            else
                sz(1)=0;
            end
                
            for i=sz(1):-1:1
                if(app.rot_constraint_matrix(i,3)==1)
                    p=2*app.rot_constraint_matrix(i,1);
                     pmass(:,p)=[];
                     pmass(p,:)=[];
                    pstiff(:,p)=[];
                    pstiff(p,:)=[];      
                end
            end
            
            sz=size(pmass);
            app.num=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            etype=1;
             
            % num total dof
            % nem number of dof with enforced acceleration
             
            disp(' Track Changes ');  
            
            % num = total dof - constrained rotational dof
            % ea = Tz dof enforced acceleration
            
            % fprintf(' nem=%d  num=%d  \n',nem,num);
            % ea
            
            [app.ngw]=track_changes(app.nem_ea,app.num,app.ea);
             
            dtype=2; % display partitioned matrices
            
            %% out1=sprintf('\n ** num=%d  nem=%d \n',num,nem);
            %% disp(out1);
            
            
            fprintf(' ibc=%d \n',ibc);
            
            disp(' Enforced Partition Matrices ');    
            
            [app.TT,app.T1,app.T2,app.Mwd,app.Mww,app.Kwd,app.Kww]=...
                            enforced_partition_matrices(app.num,app.ea,pmass,pstiff,etype,dtype);

            sz=size(app.Mwd);

            app.nem=sz(2);
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            disp(' Generalized Eigen ');            
                        
            [app.part_fn,app.part_omega,app.part_ModeShapes,app.part_MST]=Generalized_Eigen(app.Kww,app.Mww,2);   

            app.ipart=1;

            if(app.itype~=2)
                msgbox('Partitioning Complete');
            end                     

            %%%ELK
            
            [pmass,pstiff,app.nem,app.num,app.nff,ea,app.ngw,app.pdof,app.response_dof]=...
                               beam_partition(app.BC,app.global_mass_unc,app.global_stiff_unc);
            
            [app.TT,~,~,app.Mwd,app.Mww,~,app.Kww]=enforced_partition_matrices(app.num,ea,pmass,pstiff,etype,dtype);

            [~,app.omegan,app.pomn2,app.pModeShapes,app.pMST,~]=enforced_acceleration_eigenvalues(app.Kww,app.Mww,app.num,app.nff);
            
            disp(' ');
            disp(' Output arrays saved as: ');
            disp(' ');
            disp('    beam_fn ');
            disp('    beam_pf ');
            disp('    beam_modeshapes ');
            
            disp(' ');
            disp(' Mass & Stiffness arrays saved as:  ');
            disp(' ');
            disp('    beam_mass');
            disp('    beam_stiffness');
            
            assignin('base', 'beam_fn', app.fn);
            assignin('base', 'beam_pf', app.part);
            assignin('base', 'beam_modeshapes', app.ModeShapes);
            
            assignin('base', 'beam_stiffness', app.global_stiff);
            assignin('base', 'beam_mass', app.global_mass);

            name=app.ExportMassStiffnessArraysEditField.Value;
            beam_fn=app.fn;
            beam_pf=app.part;
            beam_modeshapes=app.ModeShapes;
            beam_stiffness=app.global_stiff;
            beam_mass=app.global_mass;

            beam_TT=app.TT;
            beam_Mwd=app.Mwd;
            beam_Mww=app.Mww;
            beam_Kww=app.Kww;

            save(name,'beam_fn','beam_pf','beam_modeshapes',...
                      'beam_stiffness','beam_mass','beam_TT',...
                      'beam_Mwd','beam_Mww','beam_Kww');

            num_fn=length(app.fn);
            
            app.s1='a';
            app.s2='a';
            app.s3='a';
            app.s4='a';
            app.s5='a';
            app.s6='a';
            
            app.s1=sprintf('%8.4g',app.fn(1));
            sss={app.s1};  
            
            if(num_fn>=2)
                app.s2=sprintf('%8.4g',app.fn(2));
                sss={app.s1;app.s2};  
            end
            if(num_fn>=3)
                app.s3=sprintf('%8.4g',app.fn(3));
                sss={app.s1;app.s2;app.s3};
            end
            if(num_fn>=4)
                app.s4=sprintf('%8.4g',app.fn(4));
                sss={app.s1;app.s2;app.s3;app.s4};
            end
            if(num_fn>=5)
                app.s5=sprintf('%8.4g',app.fn(5));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5};
            end
            if(num_fn>=6)
                app.s6=sprintf('%8.4g',app.fn(6));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5;app.s6};
            end
            
            app.SelectModeforPlotListBox.Items=sss;
            
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
            ss=sprintf('Select Mode for Plot \n fn (Hz)');
            app.SelectModeforPlotListBoxLabel.Text=ss;
            
            SaveDampingButtonPushed(app, event);

            app.PlotModeShapeButton.Enable='on';
            app.PlotModeShapeButton.Visible='on';

            msgbox('Natural Frequencies written to Command Window');            

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            ChangeCrossSection(app);
            Clearfn(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=8;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6';'Mode 7';'Mode 8'};
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(i)=v;
                    end            
                end   
            end
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

app.ModeShapes

            try
                close Figure 1;
            catch
            end 
    
            n=app.SelectModeforPlotListBox.Value;
            
            if(contains(n,app.s1))
                i=1;
            end
            if(contains(n,app.s2))
                i=2;
            end
            if(contains(n,app.s3))
                i=3;
            end            
            if(contains(n,app.s4))
                i=4;
            end
            if(contains(n,app.s5))
                i=5;
            end
            if(contains(n,app.s6))
                i=6;
            end  
            
            UnityModeShapes=app.ModeShapes(:,i);
            
            figure(1);
            
            app.num=50;
            
            xq=zeros(app.num*app.ne,1);
            yq=zeros(app.num*app.ne,1);
            
            kv=1;

            for j=1:app.ne
   
                if(j==1)
                    if(app.LBC==1)  % fixed
                        t1=0;
                        r1=0;
                        t2=UnityModeShapes(1);
                        r2=UnityModeShapes(2);
                        kt=1;
                        kr=2;
                    end
                    if(app.LBC==2)  % pinned
                        t1=0;
                        r1=UnityModeShapes(1);
                        t2=UnityModeShapes(2);
                        r2=UnityModeShapes(3);
                        kt=2;
                        kr=3;                        
                    end    
                    if(app.LBC==3)   % free
                        t1=UnityModeShapes(1);
                        r1=UnityModeShapes(2);
                        t2=UnityModeShapes(3);
                        r2=UnityModeShapes(4);
                        kt=3;
                        kr=4;                        
                    end   
                end    
                if(j>=2 && j<=app.ne-1)
                    t1=UnityModeShapes(kt);
                    r1=UnityModeShapes(kr);
                    kt=kt+2;
                    kr=kr+2;   
                    t2=UnityModeShapes(kt);
                    r2=UnityModeShapes(kr);
                end

                if(j==app.ne && j>=2)
                    if(app.RBC==1)  % fixed
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=0;
                    end
                    if(app.RBC==2)  % pinned
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=UnityModeShapes(end);                    
                    end    
                    if(app.RBC==3)   % free
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=UnityModeShapes(kt+2);
                        r2=UnityModeShapes(kr+2);                    
                    end   
                end                    
                
                h=app.dx(j);
                delta=1/(app.num-1);
                
                for jk=1:app.num
                    de=(jk-1)*delta;
                    e=1-de;
                    c1=3*e^2-2*e^3;
                    c2=e^2-e^3;
                    c3=1-3*e^2+2*e^3;
                    c4=-e+2*e^2-e^3;
                    yq(kv)=c1*t1 +c2*h*r1 +c3*t2 + c4*h*r2;
                    xe=h*de;
                    xq(kv)=xe+app.xx(j);
                    % fprintf('j=%d jk=%d kv=%d xq=%6.4g yq=%6.4g \n',j,jk,kv,xq(kv),yq(kv));
                    kv=kv+1;
                end            
            end
            
            yq=yq/(max(abs(yq)));
            plot(xq,yq,'b');    
                
            grid on;
            axis([0,app.L,-1.1,1.1]);
            xlabel('x (inch) ');
            ylabel('Normalized Modal displacement');
            sss=sprintf('Mode %d   fn=%8.4g Hz  with Cubic Interpolation',i,app.fn(i));
            title(sss)

            
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
            Clearfn(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
            Clearfn(app);
        end

        % Callback function
        function LengthinEditFieldValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            Clearfn(app);
        end

        % Callback function
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFrequencyHzEditField_sine.Value=app.fn(1);
        end

        % Callback function
        function SaveButton_arbitraryPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_arbitrary.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            output_name=strtrim(app.OutputArrayNameEditField_arbitrary.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');         
        end

        % Callback function
        function SaveButton_PSDPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_PSD.Value);
            
            if(contains(n,'Relative Displacement Trans'))
                data=app.rd_response_trans;
            end
            if(contains(n,'Relative Displacement PSD'))
                data=app.rd_response_psd;
            end            
            if(contains(n,'Acceleration Trans'))
                data=app.acc_response_trans;
            end
            if(contains(n,'Acceleration PSD'))
                data=app.acc_response_psd;
            end                 
            if(contains(n,'Bending Stress Trans'))
                data=app.stress_response_trans;
            end
            if(contains(n,'Bending Stress PSD'))
                data=app.stress_response_psd;
            end             
            
            output_name=strtrim(app.OutputArrayNameEditField_PSD.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');       
        end

        % Callback function
        function NumberofModesListBox_HSValueChanged(app, event)
 
        end

        % Callback function
        function BaseInputAccelGEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function PulseDurationsecEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function ResponseLocationEditField_HSValueChanged(app, event)
          
        end

        % Callback function
        function SaveButton_HSPushed(app, event)
            output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
            n=strtrim(app.OutputFunctionListBox_HS.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Value changed function: NumberofPointMassesListBox
        function NumberofPointMassesListBoxValueChanged(app, event)
            PointMass(app);
            Clearfn(app);
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed2(app, event)
            
            app.titlea='Trans';
            
            PreProcess(app);
            if(app.kflag==999)
                return;
            end   

            disp('app.contraint_matrix')
            app.constraint_matrix

            try
                nm=length(app.contraint_matrix(:,1));
            catch
                warndlg('app.contraint_matrix failed');
                return;
            end

            
            app.fmin=app.StartFrequencyHzEditField.Value;
            app.fmax=app.EndFrequencyHzEditField.Value;

            pre_trans(app);

            pref=app.OutputArrayPrefixEditField.Value;

            if(app.chplot~=2)
                a=sprintf('%s_accel_max',pref);
                pv=sprintf('%s_pv_max',pref);
                rd=sprintf('%s_rel_disp_max',pref);
                st=sprintf('%s_stress_max',pref);

                assignin('base',a, app.ppp_a);
                assignin('base',pv, app.ppp_pv);
                assignin('base',rd, app.ppp_rd);
                assignin('base',st, app.ppp_st);
            end   

            if(app.chplot>=2)
                a=sprintf('%s_accel_node_%d',pref,app.node_near);
                pv=sprintf('%s_pv_node_%d',pref,app.node_near);
                rd=sprintf('%s_rel_disp_node_%d',pref,app.node_near);
                st=sprintf('%s_stress_node_%d',pref,app.node_near);

                assignin('base',a, app.qqq_a);
                assignin('base',pv, app.qqq_pv);
                assignin('base',rd, app.qqq_rd);
                assignin('base',st, app.qqq_st);            
            end   

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';  
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = char(app.OutputFunctionListBox.Items);
            im=app.OutputFunctionListBox.Value;
            [p]=find_index(Items,im); 

            if(contains(app.titlea,'PSD'))

                if(p==1)
                    data=app.acc_response_psd;
                end
                if(p==2)
                    data=app.rd_response_psd;
                end
                if(p==3)
                    data=app.stress_response_psd;
                end
            end
            if(contains(app.titlea,'Trans'))

                if(p==1)
                    data=app.atrans;
                end
                if(p==2)
                    data=app.rdtrans;
                end
                if(p==3)
                    data=app.strans;
                end
            end
            if(contains(app.titlea,'Half') || contains(app.titlea,'Arb') )

                if(p==1)
                    data=app.acceleration;
                end
                if(p==2)
                    data=app.relative_disp;
                end
                if(p==3)
                    data=app.bending_stress;
                end
            end

            output_name=strtrim(app.OutputArrayNameEditField.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');     
        end

        % Button pushed function: CalculateBaseInputSine
        function CalculateBaseInputSineButtonPushed2(app, event)

            PreProcess(app);
            if(app.kflag==999)
                return;
            end             

            app.titlea='Sine';
            
            app.freq=str2num(app.ExcitationFreqHzEditField.Value);
            app.omega=2*pi*app.freq;
            base=str2num(app.BaseAccelerationGEditField.Value);
            
            if(app.kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end      
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            nu=app.number_modes;
         
            app.num_columns=min([nu length(app.fn)]);    
         
            fnv=app.fn;
            app.number_modes=length(fnv);
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            
            app.np=1;

            BeamTransCore(app);
            
            app.acc=base*app.HM_accel;

            app.HM_rd  =abs(app.HM_rd);
            rel_disp=base*app.HM_rd;

            app.omega=2*pi*app.freq;

            ss1=sprintf(' Accel = %8.4g G \n\n',app.acc);
            ss2=sprintf(' Pseudo Vel = %8.4g in/sec \n\n',rel_disp*app.omega);
            ss3=sprintf(' Rel Disp = %8.4g in \n\n',rel_disp);
            ss4=sprintf(' Stress = %8.4g psi',app.HM_stress);

            ss=sprintf('%s %s %s %s ',ss1,ss2,ss3,ss4);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';     

        end

        % Callback function
        function CalculateBaseInput_HS_2ButtonPushed(app, event)

        end

        % Button pushed function: CalculateBaseInputPSD_2
        function CalculateBaseInputPSD_2ButtonPushed(app, event)

            clear app.HM_stress;

            app.titlea='PSD';

            PreProcess(app);
            if(app.kflag==999)
                return;
            end   

            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im)
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp(' ');
            disp(' * * * * * ');
            disp(' ');            

            fmin=app.THM(1,1);
            fmax=app.THM(end,1);

            N=48;
            [app.freq,app.omega,app.np]=frequencies_octave_spaced(fmin,fmax,N);

            fnv=app.fn;
            app.number_modes=length(fnv);

            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            
            BeamTransCore(app);
            
            fmin=min(app.freq);
            fmax=max(app.freq);
            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                    y_label_stress='Stress (psi^2/Hz)';
            else
                    y_label_stress='Stress (Pa^2/Hz)';    
            end
            
            y_label_G2pHz='Accel (G^2/Hz)';
            y_label_G2G2='Accel Ratio (G^2/G^2)';
            
            if(app.iu==1)
                    y_label_accel_ratio='Accel Ratio (in^2/G^2)';
            else
                    y_label_accel_ratio='Accel Ratio (m^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_stress_accel='Stress/Accel (psi^2/G^2)';
            else
                    y_label_stress_accel='Stress/Accel (Pa^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_rel_disp='Rel Disp (in^2/Hz)';
            else
                    y_label_rel_disp='Rel Disp (m^2/Hz)';
            end

            md=6;
            
            fig_num=1;
            
            PSD_in=app.THM;

            app.HM_accel=[app.freq app.HM_accel];
            app.HM_rd=[app.freq app.HM_rd];
            app.HM_stress=[app.freq app.HM_stress];    

            t_string_st='Stress';

                    [fig_num,~]=...
                        plot_loglog_function_md_h2(fig_num,x_label,y_label_stress,t_string_st,app.HM_stress,fmin,fmax,md); 

            [app.acc_response_psd]=psd_mult_trans(PSD_in,app.HM_accel);
            [app.rd_response_psd]=psd_mult_trans(PSD_in,app.HM_rd);

            try
                [app.stress_response_psd]=psd_mult_trans(PSD_in,app.HM_stress);       
            catch
                size(PSD_in)
                size(app.HM_stess)
            end    

            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  ); 
            [~,rd_rms]=calculate_PSD_slopes_no( app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );    
            [~,stress_rms]=calculate_PSD_slopes_no( app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 

            ppp_apsd=app.acc_response_psd;    
            ppp_rdpsd=app.rd_response_psd; 
            ppp_spsd=app.stress_response_psd;

            ppp_aptrans=[app.freq app.HM_accel(:,2).^2];
            ppp_rdptrans=[app.freq app.HM_rd(:,2).^2]; 
            ppp_sptrans=[app.freq app.HM_stress(:,2).^2];        
            
            app.acc_response_trans=ppp_aptrans;    
            app.rd_response_trans=ppp_rdptrans; 
            app.stress_response_trans=ppp_sptrans;  

            app.xxr=strtrim(app.xxr);

            if(app.iu==1)
                t_string_aa=sprintf('Acceleration PSD at x=%s in  %7.3g GRMS',app.xxr,accel_rms);  
                t_string_rr=sprintf('Rel Disp PSD at x=%s in   %7.3g in RMS',app.xxr,rd_rms);
                t_string_ss=sprintf('Stress PSD at x=%s in  %7.4g psi RMS',app.xxr,stress_rms);
            else
                t_string_aa=sprintf('Acceleration PSD at x=%s m  %7.3g GRMS',app.xxr,accel_rms);  
                t_string_rr=sprintf('Rel Disp PSD at x=%s m   %7.3g m RMS',app.xxr,rd_rms);  
                t_string_ss=sprintf('Stress PSD at x=%s m   %7.4g Pa GRMS',app.xxr,stress_rms);
            end    
    
            [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_G2pHz,t_string_aa,ppp_apsd,fmin,fmax,md); 

            if(min(ppp_rdpsd(:,2))>1.e-90)
                [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label_rel_disp,t_string_rr,ppp_rdpsd,fmin,fmax,md);    
            end

            if(min(ppp_spsd(:,2))>1.e-90)           
                [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label_stress,t_string_ss,ppp_spsd,fmin,fmax,md);
            end

            string_th{1}='Acceleration PSD';
            string_th{2}='Relative Displacement PSD';
            string_th{3}='Stress PSD';           

            app.OutputFunctionListBox.Items=string_th;
            app.OutputFunctionListBox.Visible='on';
            app.OutputFunctionListBoxLabel.Visible='on';
            app.SaveButton.Visible='on';
            app.OutputArrayNameEditField.Visible='on';
            app.OutputArrayNameEditFieldLabel.Visible='on';

        end

        % Button pushed function: CalculateBaseInputMDOFSRS_2
        function CalculateBaseInputMDOFSRS_2ButtonPushed(app, event)
            
            PreProcess(app);
            if(app.kflag==999)
                return;
            end   

            try
                Items = char(app.listbox_method_srs.Items);
                im=app.listbox_method_srs.Value;
                [n]=find_index(Items,im)
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end            

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_srs.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            num_srs=length(app.THM(:,1));
            num_fn=length(app.fn);   
            
            num_fn=min([app.number_modes num_fn]);
            
            disp(' ');
            disp(' * * * * * * * * * * ');
            disp(' ');
            
            fig_num=1;
            
            app.iu=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
    
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fnn=app.fn;

            if(app.fn(1)<app.THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            while(fnn(end)>app.THM(end,1))
                fnn(end)=[];
            end 

            num_fn=length(fnn);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                          
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            % app.xx=[0. 0.5 1]*app.L;
            nxx=1;
            
            sz=size(app.ModeShapes);
            app.pdof=sz(1);
           
            ev(1,:)=app.FullModeShapes(app.response_dof,:)  
            
            accel_abssum=zeros(nxx,1);
              accel_srss=zeros(nxx,1);
              
            pv_abssum=zeros(nxx,1);
              pv_srss=zeros(nxx,1);  
            
            rd_abssum=zeros(nxx,1);
              rd_srss=zeros(nxx,1);
            
            for i=1:nxx

                for j=1:num_fn
                    
                    PFM=app.part(j)*ev(i,j);
                    % fprintf(' i=%d  j=%d  PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,PFM,j,a_srs(j));
                    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            ss1=sprintf('Acceleration (G)');
            ss2=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss1,ss2);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',accel_srss(i),accel_abssum(i));
                ss=sprintf('%s %s',ss,ss3);
            end

            ss3=sprintf('Pseudo Velocity (in/sec)');
            ss=sprintf('%s \n %s',ss,ss3);
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',pv_srss(i),pv_abssum(i));
                ss=sprintf('%s %s',ss,ss3);       
            end
           
            ss3=sprintf('Relative Displacement (in)');
            ss=sprintf('%s \n %s ',ss,ss3);    
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.3g \t %8.3g \n',rd_srss(i),rd_abssum(i));
                ss=sprintf('%s %s',ss,ss3);        
            end

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';  
            
        end

        % Button pushed function: CalculateBaseInputArbitrary_2
        function CalculateBaseInputArbitrary_2ButtonPushed(app, event)

            disp('ref 1')
        
            PreProcess(app);
            if(app.kflag==999)
                return;
            end   

            disp('ref 2')

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im)
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp('ref 5')
            
            try
                [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            catch
                warndlg('check failed');
                return;
            end

            if(app.kflag==999)
                return;
            end   

            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            app.nde=1;  %  detrend

            beam_FEA_base_arbitrary(app);
               
            PostOn(app);   
   
            % app.SaveButton.Enable='on';

            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
        end

        % Button down function: BaseExcitationResponseTab
        function BaseExcitationResponseTabButtonDown(app, event)
            PostOff(app);
        end

        % Callback function: not associated with a component
        function ResponseLocationListBoxValueChanged(app, event)
            PostOff(app);
        end

        % Value changed function: NumberofModestoIncludeListBox
        function NumberofModestoIncludeListBoxValueChanged(app, event)
            PostOff(app); 
            app.number_modes=str2num(app.NumberofModestoIncludeListBox.Value);
        end

        % Selection change function: TabGroupBase
        function TabGroupBaseSelectionChanged(app, event)
            PostOff(app);        
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            PostOff(app);
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed2(app, event)
            app.ExcitationFreqHzEditField.Value=sprintf('%8.4g',app.fn(1));
            PostOff(app);
        end

        % Value changing function: ExcitationFreqHzEditField
        function ExcitationFreqHzEditFieldValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: BaseAccelerationGEditField
        function BaseAccelerationGEditFieldValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: BaseInputAccelGEditField_3
        function BaseInputAccelGEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: PulseDurationsecEditField_3
        function PulseDurationsecEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: TotalResponseDurationsecEditField_3
        function TotalResponseDurationsecEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Button pushed function: CalculateResponseButton
        function CalculateResponseButtonPushed(app, event)
    
            clear app.THM;

            PreProcess(app);          
            if(app.kflag==999)
                return;
            end   

            app.titlea='Half-Sine';
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
                        
            base=str2num(app.BaseInputAccelGEditField_3.Value);
            dur=str2num(app.PulseDurationsecEditField_3.Value);
            TTotal=str2num(app.TotalResponseDurationsecEditField_3.Value);
            
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %      

            sr1=32/dur;
            sr2=12*app.fn(app.number_modes);
            
            sr=max([sr1 sr2]);
         
            app.dt=1/sr;
            
            NT=round(TTotal/app.dt);
            app.nt=NT;
            
            app.THM=zeros(NT,2);
            app.THM(:,1)=((1:NT)-1)*app.dt;

            NTE=floor(dur/app.dt);
            
            app.omega=pi/dur;
            
            for i=1:NTE
                app.THM(i,2)=base*sin(app.omega*app.THM(i,1));
            end
                        
            app.nde=1;  %  detrend

            beam_FEA_base_arbitrary(app);
               
            PostOn(app);   
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)

            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
            
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_th(app);
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_th(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Value changed function: NumberofElementsListBox
        function NumberofElementsListBoxValueChanged(app, event)
            Clearfn(app);
        end

        % Value changing function: LengthinEditField_2
        function LengthinEditField_2ValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: EditField_1
        function EditField_1ValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
           Clearfn(app);
        end

        % Value changing function: UniformNonstructuralMasslbmEditField
        function UniformNonstructuralMasslbmEditFieldValueChanging(app, event)
           Clearfn(app);
        end

        % Value changing function: PointMasslbmEditField
        function PointMasslbmEditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: PointMassLocationfromLeftEndinEditField
        function PointMassLocationfromLeftEndinEditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Button pushed function: UpdateListButton_srs
        function UpdateListButton_srsPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_srs
        function listbox_method_srsValueChanged(app, event)
            Items = char(app.listbox_method_srs.Items);
            im=app.listbox_method_srs.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               try
                    app.SelectInputArrayListBox_srs.Value=name;
               catch
               end
            end    
        end

        % Value changed function: LengthinEditField_2
        function LengthinEditField_2ValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            ChangeUnits(app);
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)
           
            ChoosePlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIBeamBendingFEAUIFigure = uifigure('Visible', 'off');
            app.UIBeamBendingFEAUIFigure.Name = 'UI Beam Bending FEA';
            app.TabGroup = uitabgroup(app.UIBeamBendingFEAUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Fixed';
            app.NumberofElementsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.NumberofElementsListBoxLabel.Text = 'Number of Elements';
            app.NumberofElementsListBox = uilistbox(app.GeometryBCsTab);
            app.NumberofElementsListBox.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512'};
            app.NumberofElementsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsListBoxValueChanged, true);
            app.NumberofElementsListBox.Value = '1';
            app.LengthinEditField_2Label = uilabel(app.GeometryBCsTab);
            app.LengthinEditField_2Label.Text = 'Length (in)';
            app.LengthinEditField_2 = uieditfield(app.GeometryBCsTab, 'text');
            app.LengthinEditField_2.ValueChangedFcn = createCallbackFcn(app, @LengthinEditField_2ValueChanged, true);
            app.LengthinEditField_2.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_2ValueChanging, true);
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_1.ValueChangingFcn = createCallbackFcn(app, @EditField_1ValueChanging, true);
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'text');
            app.UnitsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryBCsTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MaterialAddedMassTab = uitab(app.TabGroup);
            app.MaterialAddedMassTab.Title = 'Material & Added Mass';
            app.MaterialLabel = uilabel(app.MaterialAddedMassTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialAddedMassTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.NumberofPointMassesListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBoxLabel.Text = 'Number of Point Masses';
            app.NumberofPointMassesListBox = uilistbox(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBox.Items = {'0', '1'};
            app.NumberofPointMassesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPointMassesListBoxValueChanged, true);
            app.NumberofPointMassesListBox.Value = '0';
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNonstructuralMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.UniformNonstructuralMasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @UniformNonstructuralMasslbmEditFieldValueChanging, true);
            app.UniformNonstructuralMasslbmEditField.Value = '0';
            app.PointMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMasslbmEditFieldLabel.Text = 'Point Mass (lbm)';
            app.PointMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.PointMasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @PointMasslbmEditFieldValueChanging, true);
            app.PointMassLocationfromLeftEndinEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMassLocationfromLeftEndinEditFieldLabel.Text = 'Point Mass Location from Left End (in)';
            app.PointMassLocationfromLeftEndinEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.PointMassLocationfromLeftEndinEditField.ValueChangingFcn = createCallbackFcn(app, @PointMassLocationfromLeftEndinEditFieldValueChanging, true);
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFrequenciesTab);
            app.CalculateNaturalFrequenciesPartitionButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequenciesPartitionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesPartitionButtonPushed, true);
            app.CalculateNaturalFrequenciesPartitionButton.Text = 'Calculate Natural Frequencies & Partition';
            app.UITable_fn = uitable(app.NaturalFrequenciesTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            app.UITable_fn.RowName = {};
            app.ExportMassStiffnessArraysEditFieldLabel = uilabel(app.NaturalFrequenciesTab);
            app.ExportMassStiffnessArraysEditFieldLabel.Text = 'Export Mass & Stiffness Arrays';
            app.ExportMassStiffnessArraysEditField = uieditfield(app.NaturalFrequenciesTab, 'text');
            app.ExportMassStiffnessArraysEditField.Value = 'beam_fea_arrays.mat';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {''};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.BaseExcitationResponseTab = uitab(app.TabGroup);
            app.BaseExcitationResponseTab.Title = 'Base Excitation Response';
            app.BaseExcitationResponseTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationResponseTabButtonDown, true);
            app.TabGroupBase = uitabgroup(app.BaseExcitationResponseTab);
            app.TabGroupBase.SelectionChangedFcn = createCallbackFcn(app, @TabGroupBaseSelectionChanged, true);
            app.TransTab = uitab(app.TabGroupBase);
            app.TransTab.Title = 'Trans';
            app.StartFrequencyHzEditField_2Label = uilabel(app.TransTab);
            app.StartFrequencyHzEditField_2Label.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.EndFrequencyHzEditField_2Label = uilabel(app.TransTab);
            app.EndFrequencyHzEditField_2Label.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.CalculateBaseInputTrans = uibutton(app.TransTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed2, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.OutputArrayPrefixEditFieldLabel = uilabel(app.TransTab);
            app.OutputArrayPrefixEditFieldLabel.Text = 'Output Array Prefix';
            app.OutputArrayPrefixEditField = uieditfield(app.TransTab, 'text');
            app.OutputArrayPrefixEditField.Value = 'beam_trans';
            app.SineTab = uitab(app.TabGroupBase);
            app.SineTab.Title = 'Sine';
            app.SettoFirstfnButton = uibutton(app.SineTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed2, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.CalculateBaseInputSine = uibutton(app.SineTab, 'push');
            app.CalculateBaseInputSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSineButtonPushed2, true);
            app.CalculateBaseInputSine.Text = 'Calculate';
            app.BaseAccelerationGEditFieldLabel = uilabel(app.SineTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.SineTab, 'text');
            app.BaseAccelerationGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelerationGEditFieldValueChanging, true);
            app.BaseAccelerationGEditField.Value = '1';
            app.ExcitationFreqHzEditFieldLabel = uilabel(app.SineTab);
            app.ExcitationFreqHzEditFieldLabel.Text = 'Excitation Freq (Hz)';
            app.ExcitationFreqHzEditField = uieditfield(app.SineTab, 'text');
            app.ExcitationFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @ExcitationFreqHzEditFieldValueChanging, true);
            app.HalfSineTab = uitab(app.TabGroupBase);
            app.HalfSineTab.Title = 'Half-Sine';
            app.BaseInputAccelGEditField_3Label = uilabel(app.HalfSineTab);
            app.BaseInputAccelGEditField_3Label.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.BaseInputAccelGEditField_3.ValueChangingFcn = createCallbackFcn(app, @BaseInputAccelGEditField_3ValueChanging, true);
            app.PulseDurationsecEditField_3Label = uilabel(app.HalfSineTab);
            app.PulseDurationsecEditField_3Label.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.PulseDurationsecEditField_3.ValueChangingFcn = createCallbackFcn(app, @PulseDurationsecEditField_3ValueChanging, true);
            app.TotalResponseDurationsecEditField_3Label = uilabel(app.HalfSineTab);
            app.TotalResponseDurationsecEditField_3Label.Text = 'Total Response Duration (sec)';
            app.TotalResponseDurationsecEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.TotalResponseDurationsecEditField_3.ValueChangingFcn = createCallbackFcn(app, @TotalResponseDurationsecEditField_3ValueChanging, true);
            app.CalculateResponseButton = uibutton(app.HalfSineTab, 'push');
            app.CalculateResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateResponseButtonPushed, true);
            app.CalculateResponseButton.Text = 'Calculate Response';
            app.PSDTab = uitab(app.TabGroupBase);
            app.PSDTab.Title = 'PSD';
            app.CalculateBaseInputPSD_2 = uibutton(app.PSDTab, 'push');
            app.CalculateBaseInputPSD_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputPSD_2ButtonPushed, true);
            app.CalculateBaseInputPSD_2.Text = 'Calculate';
            app.listbox_method_psd = uilistbox(app.PSDTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.PSDTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.SelectInputArrayListBox_2Label = uilabel(app.PSDTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.PSDTab);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.PSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.UpdateListButton_psd = uibutton(app.PSDTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.ArbitraryTab = uitab(app.TabGroupBase);
            app.ArbitraryTab.Title = 'Arbitrary ';
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel_2 = uilabel(app.ArbitraryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel_2.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.CalculateBaseInputArbitrary_2 = uibutton(app.ArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitrary_2ButtonPushed, true);
            app.CalculateBaseInputArbitrary_2.Text = 'Calculate';
            app.listbox_method = uilistbox(app.ArbitraryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ArbitraryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ArbitraryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ArbitraryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ArbitraryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.MDOFSRSTab = uitab(app.TabGroupBase);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.SpecificationQEditFieldLabel = uilabel(app.MDOFSRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.MDOFSRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.CalculateBaseInputMDOFSRS_2 = uibutton(app.MDOFSRSTab, 'push');
            app.CalculateBaseInputMDOFSRS_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputMDOFSRS_2ButtonPushed, true);
            app.CalculateBaseInputMDOFSRS_2.Text = 'Calculate';
            app.listbox_method_srs = uilistbox(app.MDOFSRSTab);
            app.listbox_method_srs.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_srs.ValueChangedFcn = createCallbackFcn(app, @listbox_method_srsValueChanged, true);
            app.listbox_method_srs.Tag = 'listbox_method';
            app.listbox_method_srs.Value = 'Array Preloaded in Matlab';
            app.text1_srs = uilabel(app.MDOFSRSTab);
            app.text1_srs.Tag = 'text1';
            app.text1_srs.Text = 'Select Data Input Method';
            app.SelectInputArrayListBox_2Label_2 = uilabel(app.MDOFSRSTab);
            app.SelectInputArrayListBox_2Label_2.Text = 'Select Input Array';
            app.SelectInputArrayListBox_srs = uilistbox(app.MDOFSRSTab);
            app.SelectInputArrayListBox_srs.Items = {'Item 1'};
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.MDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)'; ''};
            app.UpdateListButton_srs = uibutton(app.MDOFSRSTab, 'push');
            app.UpdateListButton_srs.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srsPushed, true);
            app.UpdateListButton_srs.Text = 'Update List';
            app.NumberofModesListBox_3Label_3 = uilabel(app.BaseExcitationResponseTab);
            app.NumberofModesListBox_3Label_3.Text = {'Number of Modes '; 'to Include'};
            app.NumberofModestoIncludeListBox = uilistbox(app.BaseExcitationResponseTab);
            app.NumberofModestoIncludeListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModestoIncludeListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofModestoIncludeListBoxValueChanged, true);
            app.NumberofModestoIncludeListBox.Value = '1';
            app.ImageBeamBase = uiimage(app.BaseExcitationResponseTab);
            app.SaveButton = uibutton(app.BaseExcitationResponseTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputFunctionListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox = uilistbox(app.BaseExcitationResponseTab);
            app.OutputFunctionListBox.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.OutputArrayNameEditFieldLabel = uilabel(app.BaseExcitationResponseTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.BaseExcitationResponseTab, 'text');
            app.ResultsTextAreaLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.BaseExcitationResponseTab);
            app.ReturnButton = uibutton(app.BaseExcitationResponseTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ScriptwillfindnearestnodelocationLabel = uilabel(app.BaseExcitationResponseTab);
            app.ScriptwillfindnearestnodelocationLabel.Text = 'Script will find nearest node location.';
            app.inchLabel = uilabel(app.BaseExcitationResponseTab);
            app.inchLabel.Text = 'inch';
            app.ResponseLocationfromLeftEndEditFieldLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResponseLocationfromLeftEndEditFieldLabel.Text = {'Response Location '; 'from Left End'};
            app.ResponseLocationfromLeftEndEditField = uieditfield(app.BaseExcitationResponseTab, 'text');
            app.PlotListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.BaseExcitationResponseTab);
            app.PlotListBox.Items = {'Maximums', 'Response Location Only', 'Maximums & Response Locations'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Maximums';
            app.beam_bending_aver10byTomIrvineLabel = uilabel(app.UIBeamBendingFEAUIFigure);
            app.beam_bending_aver10byTomIrvineLabel.Text = 'beam_bending_FEA_a    ver 1.4  by Tom Irvine      This script calculates the natural frequencies of a beam and its response to excitation via FEA.';
            app.ContextMenu = uicontextmenu(app.UIBeamBendingFEAUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CalculateBaseInputPSD_2.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIBeamBendingFEAUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.text1_srs.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIBeamBendingFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beam_bending_FEA_a_old

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIBeamBendingFEAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIBeamBendingFEAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIBeamBendingFEAUIFigure)
        end
    end
end