classdef beam_bending_FEA_aa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIBeamBendingFEAUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        LengthinEditFieldLabel          matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        CrossSectionListBoxLabel        matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        EditField_1Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        ImageIBeam                      matlab.ui.control.Image
        LeftBCListBoxLabel              matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        NumberofElementsListBoxLabel    matlab.ui.control.Label
        NumberofElementsListBox         matlab.ui.control.ListBox
        MaterialAddedMassTab            matlab.ui.container.Tab
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.NumericEditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.NumericEditField
        MaterialLabel                   matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        UniformNSMEditField             matlab.ui.control.NumericEditField
        NumberofPointMassesListBoxLabel  matlab.ui.control.Label
        NumberofPointMassesListBox      matlab.ui.control.ListBox
        PointMasslbmEditFieldLabel      matlab.ui.control.Label
        PointMasslbmEditField           matlab.ui.control.NumericEditField
        PointMassLocationfromLeftEndinEditFieldLabel  matlab.ui.control.Label
        PointMassLocationfromLeftEndinEditField  matlab.ui.control.NumericEditField
        NaturalFrequenciesTab           matlab.ui.container.Tab
        CalculateNaturalFrequenciesPartitionButton  matlab.ui.control.Button
        PlotModeShapeButton             matlab.ui.control.Button
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        DampingTab                      matlab.ui.container.Tab
        DampingTypeListBoxLabel         matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        ModalDistributionListBox        matlab.ui.control.ListBox
        UITable                         matlab.ui.control.Table
        SaveDampingButton               matlab.ui.control.Button
        BaseInputTransTab               matlab.ui.container.Tab
        CalculateBaseInputTrans         matlab.ui.control.Button
        StartFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        EndFrequencyHzEditFieldLabel    matlab.ui.control.Label
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        NumberofModesListBoxLabel       matlab.ui.control.Label
        NumberofModesListBox            matlab.ui.control.ListBox
        SaveTransButton                 matlab.ui.control.Button
        OutputFunctionListBoxLabel      matlab.ui.control.Label
        OutputFunctionListBox_Trans     matlab.ui.control.ListBox
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputArrayNameEditField_Trans  matlab.ui.control.EditField
        ImageBeamBase                   matlab.ui.control.Image
        TransmissibilityforBaseExcitationLabel  matlab.ui.control.Label
        BaseInputSineTab                matlab.ui.container.Tab
        CalculateBaseInputSine          matlab.ui.control.Button
        ExcitationFrequencyHzLabel      matlab.ui.control.Label
        ExcitationFrequencyHzEditField_sine  matlab.ui.control.NumericEditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        BaseAccelerationGEditField      matlab.ui.control.NumericEditField
        NumberofModesListBox_2Label     matlab.ui.control.Label
        NumberofModesListBox_sine       matlab.ui.control.ListBox
        SettoFirstfnButton              matlab.ui.control.Button
        ImageBeamBase_sine              matlab.ui.control.Image
        BaseInputHalfSineTab            matlab.ui.container.Tab
        CalculateBaseInput_HS           matlab.ui.control.Button
        ImageBeamBase_HS                matlab.ui.control.Image
        NumberofModesListBox_2Label_2   matlab.ui.control.Label
        NumberofModesListBox_HS         matlab.ui.control.ListBox
        ResponsetoHalfSineBaseInputLabel  matlab.ui.control.Label
        BaseInputAccelGEditFieldLabel   matlab.ui.control.Label
        BaseInputAccelGEditField        matlab.ui.control.NumericEditField
        PulseDurationsecLabel           matlab.ui.control.Label
        PulseDurationsecEditField       matlab.ui.control.NumericEditField
        SaveButton_HS                   matlab.ui.control.Button
        OutputFunctionListBox_2Label_3  matlab.ui.control.Label
        OutputFunctionListBox_HS        matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label_3  matlab.ui.control.Label
        OutputArrayNameEditField_HS     matlab.ui.control.EditField
        TotalResponseDurationsecEditFieldLabel  matlab.ui.control.Label
        TotalResponseDurationsecEditField  matlab.ui.control.NumericEditField
        BaseInputPSDTab                 matlab.ui.container.Tab
        CalculateBaseInputPSD           matlab.ui.control.Button
        ImageBeamBase_PSD               matlab.ui.control.Image
        NumberofModesListBox_2Label_3   matlab.ui.control.Label
        NumberofModesListBox_PSD        matlab.ui.control.ListBox
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        InputArrayNameEditField_2Label  matlab.ui.control.Label
        InputArrayNameEditField_PSD     matlab.ui.control.EditField
        SaveButton_PSD                  matlab.ui.control.Button
        OutputFunctionListBox_2Label_2  matlab.ui.control.Label
        OutputFunctionListBox_PSD       matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        BaseInputArbitraryTab           matlab.ui.container.Tab
        CalculateBaseInputArbitrary     matlab.ui.control.Button
        NumberofModesListBox_3Label     matlab.ui.control.Label
        NumberofModesListBox_arbitrary  matlab.ui.control.ListBox
        TheinputarraymusthavetwocolumnstimesecaccelGLabel  matlab.ui.control.Label
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField_arbitrary  matlab.ui.control.EditField
        ImageBeamBase_arbitrary         matlab.ui.control.Image
        SaveButton_arbitrary            matlab.ui.control.Button
        OutputFunctionListBox_2Label    matlab.ui.control.Label
        OutputFunctionListBox_arbitrary  matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        OutputArrayNameEditField_arbitrary  matlab.ui.control.EditField
        DetrendDisplacementListBoxLabel  matlab.ui.control.Label
        DetrendDisplacementListBox      matlab.ui.control.ListBox
        BaseInputMDOFSRSTab             matlab.ui.container.Tab
        CalculateBaseInputMDOFSRS       matlab.ui.control.Button
        ImageBeamBase_MDOFSRS           matlab.ui.control.Image
        NumberofModesListBox_3Label_2   matlab.ui.control.Label
        NumberofModesListBox_MDOFSRS    matlab.ui.control.ListBox
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        SpecificationQEditField         matlab.ui.control.NumericEditField
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        InputArrayNameEditField_2Label_2  matlab.ui.control.Label
        InputArrayNameEditField_MDOFSRS  matlab.ui.control.EditField
        ReturnButton                    matlab.ui.control.Button
        beam_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabsLocationButtonGroup         matlab.ui.container.ButtonGroup
        TopButton                       matlab.ui.control.ToggleButton
        RightButton                     matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        L   % Length
        E   % Face Sheet elastic modulus
        rho % Face Sheet mass density
        fn  % Natural frequencies
        dm  % Damping
        omegan % Angular natural frequencies
        ModeShapes % Mode Shapes
        MST  % Mode Shapes Transpose
        cna  % Distance to neutral axis
        x    % x length along beam for mode shapes
        y    % Mode shape
        xx   % x length along beam for mode shapes
        ZZ   % Mode shape
        sq_mass % sqrt of mass
        MOI % Area moment of intertia
        C   % Modal constant
        beta % eigenvalue parameter
        part % participation factor
        s1  % modal frequency 1
        s2  % modal frequency 2
        s3  % modal frequency 3
        s4  % modal frequency 4
        s5  % modal frequency 5
        s6  % modal frequency 6
        RBC % Right boundary condition
        LBC % Left boundary condition
        BC  % Boundary condition
        bending_stress % 
        shear_force    % 
        bending_moment % 
        absolute_accel % 
        relative_vel   % 
        relative_disp  %   
        acceleration   %
        relative_velocity     %
        relative_displacement %   
        acc_response_psd %
	rv_response_psd  %
        rd_response_psd  %
        acc_response_trans    %
        rd_response_trans     %        
        moment_response_psd   %            
        shear_response_psd    %
        stress_response_psd   %
        stress_response_trans %        
        atrans  %
        rdtrans %
        strans  %

        global_stiff     %
        global_mass      %
        global_stiff_unc %
        global_mass_unc  %   
        
        dof % degrees-of-freedom
        iu % iu=1 for English unapp.Kwwits
        ne % number of elements
        dof_status % dof status
        dx % element length array;
        
        pomegan  %
        pomn2    %
        pModeShapes %
        pMST     %
        pdof     %
        mid_dof  %
        Mww  %
        Mwd  %
        Kww  %
        ngw  %
        nff  %
        nem  %
        num  %
        TT   %

    end
    
    methods (Access = private)
        
        function CloseFigures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
        
        function ChangeMaterials(app)
            value = app.MaterialListBox.Value;
            
            app.E=0;
            app.rho=0;            
            
            if(contains(value,'Aluminum'))
                app.E=1e+007;
                app.rho=0.1;                  
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Steel'))
                app.E=3e+007;
                app.rho= 0.28;               
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Copper'))
                app.E=1.6e+007;
                app.rho=  0.322;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'G10'))
                app.E=2.7e+006;
                app.rho=  0.065;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Titanium'))
                app.E=1.6e+07;           
                app.rho=0.163;                
            end
             value = app.MaterialListBox.Value;
            if(contains(value,'Graphite/Epoxy'))
                app.E=1e+007;
                app.rho=0.058;                           
            end        
            app.ElasticModuluspsiEditField.Value=app.E;
            app.MassDensitylbmin3EditField.Value=app.rho;        
            
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            app.SaveTransButton.Enable='off';
            app.SaveButton_arbitrary.Enable='off';
            app.SaveButton_PSD.Enable='off';
            app.SaveButton_HS.Enable='off';
        end
        
        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end
        
        function ChangeCrossSection(app)
            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                app.EditField_1Label.Text='Width (in)';
                app.EditField_2Label.Text='Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                
            end
            if(contains(n,'Pipe'))
                app.EditField_1Label.Text='Outer Diameter (in)';
                app.EditField_2Label.Text='Wall Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 app.EditField_1Label.Text='Diameter (in)';
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                app.EditField_1Label.Text='Base Width (in)';
                app.EditField_2Label.Text='Total Height (in)';
                app.EditField_3Label.Text='Flange Thickness (in)';     
                app.EditField_4Label.Text='Web Thickness (in)';  
                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                app.EditField_1Label.Text='Area (in^2)';
                app.EditField_2Label.Text='Area moment Inertia (in^4)';
                app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end
        
        function BeamBaseImage(app)
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;  
            
            app.ImageBeamBase.Visible='off';
            app.ImageBeamBase_sine.Visible='off';
            app.ImageBeamBase_arbitrary.Visible='off';   
            app.ImageBeamBase_HS.Visible='off'; 
            app.ImageBeamBase_PSD.Visible='off'; 
            app.ImageBeamBase_MDOFSRS.Visible='off'; 
            
            if(contains(nL,'Fixed') && contains(nR,'Free'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on'; 
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on'; 
                app.ImageBeamBase_MDOFSRS.Visible='on';                 
                app.ImageBeamBase.ImageSource='beam_bending_base.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base.jpg'; 
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base.jpg';
                app.ImageBeamBase_MDOFSRS.ImageSource='beam_bending_base.jpg';
                app.BC=1;
            end
            if(contains(nL,'Pinned') && contains(nR,'Pinned'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on'; 
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on'; 
                app.ImageBeamBase_MDOFSRS.Visible='on';                
                app.ImageBeamBase.ImageSource='beam_bending_base_ss.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base_ss.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base_ss.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base_ss.jpg';
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base_ss.jpg';
                app.ImageBeamBase_MDOFSRS.ImageSource='beam_bending_base_ss.jpg';
                app.BC=2;
            end   
            if(contains(nL,'Fixed') && contains(nR,'Fixed'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on';
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on'; 
                app.ImageBeamBase_MDOFSRS.Visible='on';                
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base_fixed.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_MDOFSRS.ImageSource='beam_bending_base_fixed.jpg';
                app.BC=3;
            end
            
            if(app.BC==1)
                ss1=sprintf('Acceleration Transmissibilty at Free End');
                ss2=sprintf('Relative Displacement Transmissibility at Free End');
                ss3=sprintf('Bending Stress at Fixed End');      
            end
            if(app.BC==2)
                ss1=sprintf('Acceleration Transmissibilty at Midspan');
                ss2=sprintf('Relative Displacement Transmissibility at Midspan');
                ss3=sprintf('Bending Stress at Midspan');         
            end
            if(app.BC==3)
                ss1=sprintf('Acceleration Transmissibilty at Midspan');
                ss2=sprintf('Relative Displacement Transmissibility at Midspan');    
                ss3=sprintf('Bending Stress at Fixed End');       
            end
            try
                app.OutputFunctionListBox_Trans.Items={ss1,ss2,ss3};
            catch   
            end
            
            %%%   
            if(app.BC==1)
                string_th{1}=sprintf('Acceleration Transmissibilty at Free End');
                string_th{2}=sprintf('Acceleration PSD at Free End');
                string_th{3}=sprintf('Relative Displacement Transmissibility at Free End');  
                string_th{4}=sprintf('Relative Displacement PSD at Free End');
                string_th{5}=sprintf('Bending Stress Transmissibilty at Fixed End');
                string_th{6}=sprintf('Bending Stress PSD at Fixed End');     
            end
            if(app.BC>=2)
                string_th{1}=sprintf('Acceleration Transmissibilty at Midspan');
                string_th{2}=sprintf('Acceleration PSD at Midspan');
                string_th{3}=sprintf('Relative Displacement Transmissibility at Midspan');  
                string_th{4}=sprintf('Relative Displacement PSD at Midspan');         
            end
            if(app.BC==2)
                string_th{5}=sprintf('Bending Stress Transmissibilty at Midspan');
                string_th{6}=sprintf('Bending Stress PSD at Midspan');     
            end
            if(app.BC==3)
                string_th{5}=sprintf('Bending Stress Transmissibilty at Fixed End');
                string_th{6}=sprintf('Bending Stress PSD at Fixed End');     
            end

            try
                app.OutputFunctionListBox_PSD.Items=string_th;
            catch       
            end
            
            clear string_th;
            
            if(app.BC==1)
                string_th{1}=sprintf('Absolute Acceleration at Free End');
                string_th{2}=sprintf('Relative Displacement at Free End');
                string_th{3}=sprintf('Bending Stress at Fixed End');  
            end
            if(app.BC>=2)
                string_th{1}=sprintf('Absolute Acceleration at Center');
                string_th{2}=sprintf('Relative Displacement at Center');
            end
            if(app.BC==3)
                string_th{3}=sprintf('Bending Stress at Left Fixed End');  
            end
            
            try
                app.OutputFunctionListBox_arbitrary.Items=string_th;
            catch       
            end            
       
            clear string_th;
            
            if(app.BC==1)
                string_th{1}=sprintf('Absolute Acceleration at Free End');
                string_th{2}=sprintf('Relative Displacement at Free End');
                string_th{3}=sprintf('Bending Stress at Fixed End');  
            end
            if(app.BC>=2)
                string_th{1}=sprintf('Absolute Acceleration at Center');
                string_th{2}=sprintf('Relative Displacement at Center');
            end
            if(app.BC==3)
                string_th{3}=sprintf('Bending Stress at Left Fixed End');  
            end
            
            try
                app.OutputFunctionListBox_HS.Items=string_th;
            catch       
            end              
            
        end
        
        function PointMass(app)
        
            n=str2num(app.NumberofPointMassesListBox.Value);
        
            app.PointMasslbmEditField.Visible='off';
            app.PointMassLocationfromLeftEndinEditField.Visible='off';
            app.PointMasslbmEditFieldLabel.Visible='off';
            app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='off';
            
            if(n==1)
                app.PointMasslbmEditField.Visible='on';
                app.PointMassLocationfromLeftEndinEditField.Visible='on';
                app.PointMasslbmEditFieldLabel.Visible='on';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='on';                 
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeMaterials(app);
            ChangeDamping(app);
            ChangeCrossSection(app);
            BeamBaseImage(app);
            PointMass(app);
            ss=sprintf('Response Location\nDistance from    \nLeft Boundary (in)  ');
            % app.ResponseLocationinEditFieldLabel.Text=ss;
            app.iu=1;
            
            for t = 1:numel(app.TabGroup.Children)
                app.TabGroup.Children(t).Tooltip = app.TabGroup.Children(t).Title;
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)

            ChangeMaterials(app);
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed(app, event)
       
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end      
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            nu=app.NumberofModesListBox.Value;
            
            number_modes=round(str2double(nu));
            num_columns=number_modes;
            
            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
            fmin=fstart;

            N=48;
            [freq,omega,np]=frequencies_octave_spaced(fmin,fmax,N);

            om2=omega.^2;

            QE=app.ModeShapes;
            fnv=app.fn;
            num_modes=length(fnv);
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            sz=size(QE);
            if(num_columns>sz(2))
                num_columns=sz(2);
            end
            
            if(num_columns> length(app.fn))
                num_columns=length(app.fn);
            end
            
            fprintf('\n number of modes included =%d \n',num_columns);

            [~,HM_accel_mid,HM_accel_right,~,HM_rd_mid,HM_rd_right,adisp]=...
                BeamTransCore(app.nff,np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.mid_dof);
            
            dxx=mean(app.dx);
            pL=dxx;
 
            
            if(app.iu==1)
                    y_label_st='Stress/Accel (psi/G)';
            else
                    y_label_st='Stress/Accel (Pa/G)';    
            end
            
            if(app.iu==1)
                    y_label_rd='(Rel Disp)/Accel (in/G)';
            else
                    y_label_rd='(Rel Disp)/Accel (m/G)';    
            end
                
            x_label='Frequency (Hz)';
            y_label_ar='Accel Ratio (G/G)';
            
            fmin=min(freq);
            fmax=max(freq);

            %
            %  http://home.sogang.ac.kr/sites/cmlab/Coursework/course001/Lists/b141/Attachments/1/Ch.02%20(pp.%2017-40)%20Bars%20and%20Beams.%20Linear%20Static%20Analysis.pdf
            %
            
            if(app.iu==1)
               rs=386; 
            else
               rs=9.81; 
            end
            
            % HM_rd_left =abs(HM_rd_left*rs);
            HM_rd_mid  =abs(HM_rd_mid*rs);
            HM_rd_right=abs(HM_rd_right*rs);
            
            HM_stress_left=zeros(np,1);
            HM_stress_mid=zeros(np,1);
            % HM_stress_right=zeros(np,1);
            
            md=4;
            
            if(app.BC==1)
                t_string_at='Accleration Transmissibility at Free End';    
                t_string_rt='Relative Displacement Transmissibility at Free End Rel to Base';
                t_string_st='Stress Transmissibility at Fixed End';    
            end
            if(app.BC==2)
                t_string_at='Accleration Transmissibility at Midspan';
                t_string_rt='Relative Displacement Transmissibility at Midspan Rel to Base';    
                t_string_st='Stress Transmissibility at Midspan';      
            end
            if(app.BC==3)
                t_string_at='Accleration Transmissibility at Midspan';
                t_string_rt='Relative Displacement Transmissibility at Midspan'; 
                t_string_st='Stress Transmissibility at Left Fixed End Relative to Base';      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.BC==1 || app.BC==3)     
            
                px=0;
                [B]=beam_stress_B(px,pL);
            
                for j=1:np
            
                    Y=zeros(app.pdof+1,1);
            
                    Y(1)=(adisp(j,1));
            
                    Y(3:(app.pdof+1))=(adisp(j,2:app.pdof));
                
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
             
                    HM_stress_left(j)=rs*abs(app.cna*app.E*B*d);
                
                end
                
            end 
            
            if(app.BC==2)
                
                px=pL;
                [B]=beam_stress_B(px,pL);
                
                L1=app.mid_dof-2;
                L2=app.mid_dof-1;    
                L3=app.mid_dof;
                L4=app.mid_dof+1; 
                            
                for j=1:np
                    
                    Y(1)=(adisp(j,L1));
                    Y(2)=(adisp(j,L2));
                    Y(3)=(adisp(j,L3));
                    Y(4)=(adisp(j,L4));
                
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
             
                    HM_stress_mid(j)=rs*abs(app.cna*app.E*B*d);
                
                end
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.BC==1)
                app.atrans=[freq HM_accel_right];
                app.rdtrans=[freq HM_rd_right];
                app.strans=[freq HM_stress_left];     
            end
            if(app.BC==2)
                app.atrans=[freq HM_accel_mid];
                app.rdtrans=[freq HM_rd_mid];
                app.strans=[freq HM_stress_mid];   
            end
            if(app.BC==3)
                app.atrans=[freq HM_accel_mid];
                app.rdtrans=[freq HM_rd_mid];
                app.strans=[freq HM_stress_left]; 
            end
            
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_ar,t_string_at,app.atrans,fmin,fmax,md);    
                
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_rd,t_string_rt,app.rdtrans,fmin,fmax,md);        
            
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_st,t_string_st,app.strans,fmin,fmax,md);    
            
            app.SaveTransButton.Enable='on';    
                
        end

        % Button pushed function: CalculateBaseInputSine
        function CalculateBaseInputSineButtonPushed(app, event)
     
            freq=app.ExcitationFrequencyHzEditField_sine.Value;
            omega=2*pi*freq;
            base=app.BaseAccelerationGEditField.Value;
            
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end      
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            nu=str2num(app.NumberofModesListBox_sine.Value);
            
            num_columns=min([nu length(app.fn)]);    

            om2=omega.^2;

         
            fnv=app.fn;
            num_modes=length(fnv);
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            
            fprintf('\n number of modes included =%d \n',num_columns);
            %
            
            np=1;

            [~,HM_accel_mid,HM_accel_right,~,HM_rd_mid,HM_rd_right,adisp]=...
                BeamTransCore(app.nff,np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.mid_dof);

            dxx=mean(app.dx);
            pL=dxx;
            
            %%%%

            if(app.iu==1)
                rs=386;
            else
                rs=9.81;
            end
            
            % HM_rd_left =abs(HM_rd_left*rs);
            HM_rd_mid  =abs(HM_rd_mid*rs);
            HM_rd_right=abs(HM_rd_right*rs);
            
            HM_stress_left=zeros(np,1);
            HM_stress_mid=zeros(np,1);
            % HM_stress_right=zeros(np,1);            
            
            if(app.BC==1 || app.BC==3)     
            
                px=0;
                [B]=beam_stress_B(px,pL);
            
                for j=1:np
            
                    Y=zeros(app.pdof+1,1);
            
                    Y(1)=(adisp(j,1));
            
                    Y(3:(app.pdof+1))=(adisp(j,2:app.pdof));
                
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                
                    HM_stress_left(j)=rs*abs(app.cna*app.E*B*d);
                
                end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                acc=base*HM_accel_right;
                rel_disp=base*HM_rd_right;    
                stress=base*HM_stress_left;   

                fprintf('\n Beam Free End Accel = %8.4g G \n',acc);

                pv=HM_rd_right*omega;
            
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Free End Pseudo Velocity = %8.4g in/sec (assuming resonant excitation)',pv);    
                else
                    out1=sprintf(' Beam Free End Pseudo Velocity = %8.4g m/sec (assuming resonant excitation)',pv);       
                end
            
                disp(out1);
            
            %%
            
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Free End Relative Displacement = %8.4g in ',rel_disp);    
                else
                    out1=sprintf(' Beam Free End Relative Displacement = %8.4g mm ',rel_disp*1000);       
                end
            
                disp(out1);
            
            %%
            
            end 

            if(app.BC>=2)
                
                acc=base*HM_accel_mid;
                rel_disp=base*HM_rd_mid;    
                
                
                disp(' ');
                out1=sprintf(' Beam Midspan Accel = %8.4g G ',acc);
                disp(out1);
            
            %%
            
                pv=HM_rd_mid*omega;
            
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Midspan Pseudo Velocity = %8.4g in/sec (assuming resonant excitation)',pv);    
                else
                    out1=sprintf(' Beam Midspan Pseudo Velocity = %8.4g m/sec (assuming resonant excitation)',pv);       
                end
            
                disp(out1);
            
            %%
            
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Midspan Relative Displacement = %8.4g in ',rel_disp);    
                else
                    out1=sprintf(' Beam Midspan Relative Displacement = %8.4g mm ',rel_disp*1000);       
                end
            
                disp(out1);
            
                    
            end
                
            if(app.BC==2)
                px=pL;
                [B]=beam_stress_B(px,pL);
                
                L1=app.mid_dof-2;
                L2=app.mid_dof-1;    
                L3=app.mid_dof;
                L4=app.mid_dof+1; 
                
                for j=1:np
                    
                    Y(1)=(adisp(j,L1));
                    Y(2)=(adisp(j,L2));
                    Y(3)=(adisp(j,L3));
                    Y(4)=(adisp(j,L4));
                
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
             
                    HM_stress_mid(j)=rs*abs(app.cna*app.E*B*d);
                
                end
                
                stress=base*HM_stress_mid;   
            end
            
            if(app.BC==1)
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Fixed End Stress = %8.4g psi ',stress);    
                else
                    out1=sprintf(' Beam Fixed End Stress = %8.4g Pa ',stress);       
                end
                disp(out1);
            end 
            if(app.BC==2)
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Midspan Stress = %8.4g psi ',stress);    
                else
                    out1=sprintf(' Beam Midspan Stress = %8.4g Pa ',stress);       
                end
                disp(out1);    
            end 
            if(app.BC==3)
                disp(' ');
                if(app.iu==1)
                    out1=sprintf(' Beam Left Fixed End Stress = %8.4g psi ',stress);    
                else
                    out1=sprintf(' Beam Left Fixed End Stress = %8.4g Pa ',stress);       
                end
                disp(out1);    
            end 
            
            msgbox('Results written to Command Window ');
                       
        end

        % Button pushed function: CalculateBaseInput_HS
        function CalculateBaseInput_HSButtonPushed(app, event)
            
            CloseFigures(app);
            
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
                  
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end         
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            fig_num=1;
            
            tpi=2*pi;
            
            base=app.BaseInputAccelGEditField.Value;
            dur=app.PulseDurationsecEditField.Value;

            TTotal=app.TotalResponseDurationsecEditField.Value;

            fnv=app.fn;
            mfnv=length(fnv);

            mbox=str2num(app.NumberofModesListBox_HS.Value);
            
            num_modes=min([mfnv mbox]);           

            fprintf('\n number of modes included =%d \n',num_modes);
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %      

            sr1=20/dur;
            sr2=12*app.fn(num_modes);
            
            sr=max([sr1 sr2]);
         
            dt=1/sr;
            
            NT=round(TTotal/dt);
            nt=NT;
            
            THM=zeros(NT,2);
            THM(:,1)=((1:NT)-1)*dt;
            
            NTE=floor(dur/dt);
            
            omega=pi/dur;
            
            for i=1:NTE
                THM(i,2)=base*sin(omega*THM(i,1));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integrating acceleration ');
            
            ndof=app.pdof;
            
            f=THM(:,2);
            
            if(app.iu==1)
               f=f*386; 
            else
               f=f*9.81; 
            end    
            
            if(app.BC==1)
                fin=f;
            else
                fin=[ f f]; 
            end
            
            [dvelox]=integrate_function(f,dt);
            [ddisp]=integrate_function(dvelox,dt);
            %
            %  Modal Transient
            %
            clear omegad;
            omegad=zeros(app.nff,1);

            for i=1:length(app.dm)
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
            end

            noo=num_modes;
        
            for i=1:noo
                
                if(sr<10*app.fn(i))
                   num_modes=i-1;
                   fprintf('\n Number of included modes = %d due to input data sample rate limitation. \n',num_modes);         
                   break; 
                end
                
            end

            fprintf('\n sample rate = %8.4g samples/sec \n',sr);

            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:num_modes 
               fprintf(' %d.  %8.3g  %6.3g \n',i,app.fn(i),app.dm(i)); 
            end

            Q=app.ModeShapes(:,1:num_modes);
            QT=Q';

            ff=zeros(app.nem,1);
            
            QTapp.Mwd=QT*app.Mwd;
            
            for i=1:length(f)
                ff(1:app.nem,i)=f(i);
            end
            
            disp(' ');
            disp(' Calculating FP ');
        
            FP=-QTapp.Mwd*ff;
            
            disp(' Completed FP ')    

            [a1,a2,df1,df2,df3,~,~,~,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(num_modes,app.omegan,app.dm,dt);

            nx=zeros(nt,num_modes);
            na=zeros(nt,num_modes);
        
            for j=1:num_modes
            %
                amodal=FP(j,:);
            %
            %  displacement
            %
                d_forward=[   df1(j),  df2(j), df3(j) ];
                d_back   =[     1, -a1(j), -a2(j) ];
                d_resp=filter(d_forward,d_back,amodal);
            %       
            %  acceleration
            %   
                a_forward=[   af1(j),  af2(j), af3(j) ];
                a_back   =[     1, -a1(j), -a2(j) ]; 
                a_resp=filter(a_forward,a_back,amodal);
            %
                nx(:,j)=d_resp;  % displacement
                na(:,j)=a_resp;  % acceleration  
            %
            end
            %
            clear amodal;
            clear FP;
            clear d_resp;
            clear a_resp;
            
            disp(' Calculate a');
            
            d=zeros((ndof-app.nem),nt);
            d(:,:)=(Q*(nx(:,:))');
            clear nx;
            
            disp(' Calculate d');
            a=zeros((ndof-app.nem),nt);
            a(:,:)=(Q*(na(:,:))'); 
            clear na;
 
            %%%%

            d=d';
            a=a';
            
            fin=fix_size(fin);
            
            disp(' Transformation a  ');
            
            Adw=[fin a];
            
            sz=size(Adw);
            mqq=sz(2);
            
            A=zeros(nt,mqq);
            D=zeros(nt,mqq);

            A(:,:)=(app.TT*Adw(:,:)')';
            clear Adw;
            
            disp(' Transformation d ');
            
            if(app.BC==1)
                Ddw=[ddisp d];
            else
                Ddw=[ddisp ddisp d];    
            end    
            
            D(:,:)=(app.TT*Ddw(:,:)')';
            clear Ddw;

            acc=zeros(nt,mqq);
            ddd=zeros(nt,mqq);
            
            disp(' Sort  A'); 
            acc(:,app.ngw(:))=A(:,:);
            clear A;
            
            disp(' Sort  D'); 
            ddd(:,app.ngw(:))=D(:,:);
            clear D;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Generate complete vectors ');
            
            zz=zeros(nt,1);
            
            sz=size(acc);
            nmn=sz(2);
            
            acc=[ acc(:,1) zz acc(:,2:nmn)];
            ddd=[ ddisp zz ddd(:,2:nmn)];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Integration ');
            
            pdx=mean(app.dx);            
            
            nde=2;  %  no detrend
            
            if(app.BC==1)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC1_HS(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM);
            end
            if(app.BC==2)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC2_HS(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM,app.mid_dof);
            end
            if(app.BC==3)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC3_HS(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM);
            end
   
            app.SaveButton_HS.Enable='on';

            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
            
                      
        end

        % Button pushed function: CalculateBaseInputPSD
        function CalculateBaseInputPSDButtonPushed(app, event)
           
            CloseFigures(app);
                    
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end        
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end     
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            if(app.BC==1)
                disp(' Fixed-Free Beam');
            end
            if(app.BC==2)
                disp(' Pinned-Pinned Beam');    
            end
            if(app.BC==3)
                disp(' Fixed-Fixed Beam');    
            end
            disp(' ');

            try
                FS=app.InputArrayNameEditField_PSD.Value;
                THM=evalin('base',FS);
            catch
                warndlg(' Input PSD array not found.');
                return; 
            end    
            
            fmin=THM(1,1);
            fmax=THM(end,1);

            N=48;
            [freq,omega,np]=frequencies_octave_spaced(fmin,fmax,N);

            om2=omega.^2;

            fnv=app.fn;
            num_modes=length(fnv);

            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            num_columns=str2num(app.NumberofModesListBox_PSD.Value);

            num_columns=min([num_columns length(app.fn)  ]);
            
            fprintf('\n number of modes included =%d \n',num_columns);
                      
            [~,HM_accel_mid,HM_accel_right,HM_rd_left,HM_rd_mid,HM_rd_right,adisp]=...
                BeamTransCore(app.nff,np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.mid_dof);
            
            dxx=mean(app.dx);
            pL=dxx;

            %
            %  http://home.sogang.ac.kr/sites/cmlab/Coursework/course001/Lists/b141/Attachments/1/Ch.02%20(pp.%2017-40)%20Bars%20and%20Beams.%20Linear%20Static%20Analysis.pdf
            %

            if(app.iu==1)
               rs=386; 
            else
               rs=9.81; 
            end

            HM_rd_left =abs(HM_rd_left*rs);
            HM_rd_mid  =abs(HM_rd_mid*rs);
            HM_rd_right=abs(HM_rd_right*rs);
            
            HM_stress_left=zeros(np,1);
            HM_stress_mid=zeros(np,1);
            HM_stress_right=zeros(np,1);

            mid=(app.ne+1);            % equivalent to rotational dof number at center of beam
            Y=zeros(np,2*(app.ne+1));  % total unconstrained dof 
            
            if(app.BC==1) % Fixed-Free
               for j=1:np
                   Y(j,:)=[adisp(j,1) 0 adisp(j,2:end)];
               end     
            end
            if(app.BC==2) % Simply-Supported 
                for j=1:np
                   Y(j,:)=adisp(j,:);
                end 
            end  
            if(app.BC==3) % Fixed-Fixed
               for j=1:np
                   Y(j,:)=[adisp(j,1) 0 adisp(j,2:end) 0];
               end              
            end
            
            s=0;  % left side of element
            [B]=beam_stress_B(s,pL);
            
            for j=1:np
                d=[Y(j,1) Y(j,2) Y(j,3) Y(j,4) ]';
                HM_stress_left(j)=rs*abs(app.cna*app.E*B*d); 
            end

            s=1; % right side of element
            [B]=beam_stress_B(s,pL);
            
            neh=app.ne/2;
            
            for j=1:np
                k=2*neh+1;
                d=[Y(j,k)  Y(j,k+1) Y(j,k+2) Y(j,k+3) ]';            
                HM_stress_mid(j)=rs*abs(app.cna*app.E*B*d);                
                d=[Y(j,end-3)  Y(j,end-2) Y(j,end-1) Y(j,end) ]'; 
                HM_stress_right(j)=rs*abs(app.cna*app.E*B*d);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % HM_accel_left=[freq HM_accel_left  ];
            HM_accel_mid=[freq  HM_accel_mid ];
            HM_accel_right=[freq  HM_accel_right ];
             
            % HM_rd_left=[freq HM_rd_left  ];
            HM_rd_mid=[freq  HM_rd_mid ];
            HM_rd_right=[freq HM_rd_right  ];
             
            HM_stress_left=[freq  HM_stress_left ];
            HM_stress_mid=[freq HM_stress_mid  ];
            HM_stress_right=[freq HM_stress_right  ];
            
            assignin('base','HL',HM_stress_left);
            assignin('base','HM',HM_stress_mid);
            assignin('base','HR',HM_stress_right);

                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=min(freq);
            fmax=max(freq);
            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                    y_label_stress='Stress (psi^2/Hz)';
            else
                    y_label_stress='Stress (Pa^2/Hz)';    
            end
            
            y_label_G2pHz='Accel (G^2/Hz)';
            y_label_G2G2='Accel Ratio (G^2/G^2)';
            
            if(app.iu==1)
                    y_label_accel_ratio='Accel Ratio (in^2/G^2)';
            else
                    y_label_accel_ratio='Accel Ratio (m^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_stress_accel='Stress/Accel (psi^2/G^2)';
            else
                    y_label_stress_accel='Stress/Accel (Pa^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_rel_disp='Rel Disp (in^2/Hz)';
            else
                    y_label_rel_disp='Rel Disp (m^2/Hz)';
            end

            md=6;
            
            fig_num=1;
            
            PSD_in=THM;
            
            if(app.BC==1)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_right);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_right);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,HM_stress_left);             
            end
            if(app.BC==2)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_mid);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_mid);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,HM_stress_mid);       
            end
            if(app.BC==3)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_mid);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_mid);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,HM_stress_left);      
            end

            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  ); 
            [~,rd_rms]=calculate_PSD_slopes_no( app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );    
            [~,stress_rms]=calculate_PSD_slopes_no( app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 

            ppp_apsd=app.acc_response_psd;    
            ppp_rdpsd=app.rd_response_psd; 
            ppp_spsd=app.stress_response_psd;

            if(app.BC==1)
                ppp_aptrans=[freq HM_accel_right(:,2).^2];
                ppp_rdptrans=[freq HM_rd_right(:,2).^2];  
                ppp_sptrans=[freq HM_stress_left(:,2).^2];    
            end
            if(app.BC==2)
                ppp_aptrans=[freq HM_accel_mid(:,2).^2];
                ppp_rdptrans=[freq HM_rd_mid(:,2).^2]; 
                ppp_sptrans=[freq HM_stress_mid(:,2).^2];        
            end
            if(app.BC==3)
                ppp_aptrans=[freq HM_accel_mid(:,2).^2];
                ppp_rdptrans=[freq HM_rd_mid(:,2).^2];       
                ppp_sptrans=[freq HM_stress_left(:,2).^2];   
            end
            
            app.acc_response_trans=ppp_aptrans;    
            app.rd_response_trans=ppp_rdptrans; 
            app.stress_response_trans=ppp_sptrans;            

            if(app.BC==1)
                t_string_at='Accleration Power Transmissibility at Free End ';  
                t_string_aa=sprintf('Accleration PSD at Free End  %7.3g GRMS',accel_rms);      
                t_string_st='Stress Power Transmissibility at Fixed End ';    

                if(app.iu==1)
                    t_string_rr=sprintf('Rel Disp PSD at Free End Relative to Base %7.3g in RMS',rd_rms);
                else
                    t_string_rr=sprintf('Rel Disp PSD at Free End Relative to Base %7.3g m RMS',rd_rms);    
                end    
            
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Fixed End  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Fixed End  %7.4g Pa GRMS',stress_rms);
                end    
                
            end
            if(app.BC>=2)
                
                t_string_at='Accleration Power Transmissibility at Midspan '; 
                t_string_aa=sprintf('Accleration PSD at Midspan  %7.3g GRMS',accel_rms);       
            
                if(app.iu==1)
                    t_string_rr=sprintf('Rel Disp PSD at Midspan Relative to Base %7.3g in RMS',rd_rms);
                else
                    t_string_rr=sprintf('Rel Disp PSD at Midspan Relative to Base %7.3g m RMS',rd_rms);    
                end      
                
            end
            if(app.BC==2)
                
                t_string_st='Stress Power Transmissibility at Midspan ';  
                
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Midspan  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Midspan  %7.4g Pa GRMS',stress_rms);
                end     
            end

            if(app.BC==3)
                
                t_string_st='Stress Power Transmissibility at Left Fixed End ';  
                
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Left Fixed End  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Left Fixed End  %7.4g Pa GRMS',stress_rms);
                end     
            end
            
 
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_G2G2,t_string_at,ppp_aptrans,fmin,fmax,md);  
               
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_G2pHz,t_string_aa,ppp_apsd,fmin,fmax,md); 

            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_rel_disp,t_string_rr,ppp_rdpsd,fmin,fmax,md);    
                
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_stress_accel,t_string_st,ppp_sptrans,fmin,fmax,md); 
            
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_stress,t_string_ss,ppp_spsd,fmin,fmax,md);

            app.SaveButton_PSD.Enable='on';
                
        end

        % Button pushed function: CalculateBaseInputArbitrary
        function CalculateBaseInputArbitraryButtonPushed(app, event)

            CloseFigures(app);
            
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
                            
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end         

            try
                FS=app.InputArrayNameEditField_arbitrary.Value;
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            fig_num=1;
            
            tpi=2*pi;

            NT=length(THM(:,1));
            nt=NT;

            EA=THM;
            
            dur=EA(NT,1)-EA(1,1);
            dt=dur/(NT-1);
            
            sr=1/dt;
            
            fprintf(' NT=%d  dur=%8.4g sec  dt=%8.4g sec \n',NT,dur,dt);

            fnv=app.fn;
            mfnv=length(fnv);

            mbox=str2num(app.NumberofModesListBox_arbitrary.Value);
            
            num_modes=min([mfnv mbox]);           

            fprintf('\n number of modes included =%d \n',num_modes);
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integrating acceleration ');
            
            ndof=app.pdof;
            
            f=THM(:,2);
            
            if(app.iu==1)
               f=f*386; 
            else
               f=f*9.81; 
            end    
            
            if(app.BC==1)
                fin=f;
            else
                fin=[ f f]; 
            end
            
            [dvelox]=integrate_function(f,dt);
            [ddisp]=integrate_function(dvelox,dt);
            %
            %  Modal Transient
            %
            clear omegad;
            omegad=zeros(app.nff,1);

            for i=1:length(app.dm)
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
            end

            noo=num_modes;
        
            for i=1:noo
                
                if(sr<10*app.fn(i))
                   num_modes=i-1;
                   fprintf('\n Number of included modes = %d due to input data sample rate limitation. \n',num_modes);         
                   break; 
                end
                
            end

            fprintf('\n sample rate = %8.4g samples/sec \n',sr);

            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:num_modes 
               fprintf(' %d.  %8.3g  %6.3g \n',i,app.fn(i),app.dm(i)); 
            end

            Q=app.ModeShapes(:,1:num_modes);
            QT=Q';

            ff=zeros(app.nem,1);
            
            QTapp.Mwd=QT*app.Mwd;
            
            for i=1:length(f)
                ff(1:app.nem,i)=f(i);
            end
            
            disp(' ');
            disp(' Calculating FP ');
        
            FP=-QTapp.Mwd*ff;
            
            disp(' Completed FP ')    

            [a1,a2,df1,df2,df3,~,~,~,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(num_modes,app.omegan,app.dm,dt);

            nx=zeros(nt,num_modes);
            na=zeros(nt,num_modes);
        
            for j=1:num_modes
            %
                amodal=FP(j,:);
            %
            %  displacement
            %
                d_forward=[   df1(j),  df2(j), df3(j) ];
                d_back   =[     1, -a1(j), -a2(j) ];
                d_resp=filter(d_forward,d_back,amodal);
            %       
            %  acceleration
            %   
                a_forward=[   af1(j),  af2(j), af3(j) ];
                a_back   =[     1, -a1(j), -a2(j) ]; 
                a_resp=filter(a_forward,a_back,amodal);
            %
                nx(:,j)=d_resp;  % displacement
                na(:,j)=a_resp;  % acceleration  
            %
            end
            %
            clear amodal;
            clear FP;
            clear d_resp;
            clear a_resp;
            
            disp(' Calculate a');
            
            d=zeros((ndof-app.nem),nt);
            d(:,:)=(Q*(nx(:,:))');
            clear nx;
            
            disp(' Calculate d');
            a=zeros((ndof-app.nem),nt);
            a(:,:)=(Q*(na(:,:))'); 
            clear na;
 
            %%%%

            d=d';
            a=a';
            
            fin=fix_size(fin);
            
            disp(' Transformation a  ');
            
            Adw=[fin a];
            
            sz=size(Adw);
            mqq=sz(2);
            
            A=zeros(nt,mqq);
            D=zeros(nt,mqq);

            A(:,:)=(app.TT*Adw(:,:)')';
            clear Adw;
            
            disp(' Transformation d ');
            
            if(app.BC==1)
                Ddw=[ddisp d];
            else
                Ddw=[ddisp ddisp d];    
            end    
            
            D(:,:)=(app.TT*Ddw(:,:)')';
            clear Ddw;

            acc=zeros(nt,mqq);
            ddd=zeros(nt,mqq);
            
            disp(' Sort  A'); 
            acc(:,app.ngw(:))=A(:,:);
            clear A;
            
            disp(' Sort  D'); 
            ddd(:,app.ngw(:))=D(:,:);
            clear D;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Generate complete vectors ');
            
            zz=zeros(nt,1);
            
            sz=size(acc);
            nmn=sz(2);
            
            acc=[ acc(:,1) zz acc(:,2:nmn)];
            ddd=[ ddisp zz ddd(:,2:nmn)];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Integration ');
            
            pdx=mean(app.dx);            
            
            nds=app.DetrendDisplacementListBox.Value;
            if(contains(nds,'Yes'))
                nde=1;
            else
                nde=2;
            end
            
            if(app.BC==1)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC1(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM);
            end
            if(app.BC==2)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC2(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM,app.mid_dof);
            end
            if(app.BC==3)
               [app.acceleration,app.relative_displacement,app.bending_stress,fig_num]=...
                   beam_FEA_base_arbitrary_BC3(ndof,acc,ddd,dt,app.fn,app.iu,nde,pdx,nt,tpi,app.cna,app.E,fig_num,THM);
            end
   
            app.SaveButton_arbitrary.Enable='on';

            msgbox('Calculation complete.  Output written to Matlab Command Window.');      

        end

        % Button pushed function: CalculateBaseInputMDOFSRS
        function CalculateBaseInputMDOFSRSButtonPushed(app, event)
            
            CloseFigures(app);
            
            kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                kflag=1;
            end
            
            if(kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end       
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end      
            
            try
                FS=strtrim(app.InputArrayNameEditField_MDOFSRS.Value);
                THM=evalin('base',FS);   
            catch
                warndlg('Input array not found');
                return;
            end
            
            num_srs=length(THM(:,1));
            num_fn=length(app.fn);   
            
            nL=str2num(app.NumberofModesListBox_MDOFSRS.Value);
            
            num_fn=min([nL num_fn]);
            
            disp(' ');
            disp(' * * * * * * * * * * ');
            disp(' ');
            
            fig_num=1;
            
            app.iu=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
    
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=THM;
            fmin=THM(1,1);
            fmax=THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.fn(1)<THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            if(app.fn(num_fn)>THM(num_srs,1))
               warndlg(' Last Natural Frequency < Last Specification Frequency ');
               return
            end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                          
            f=THM(:,1);
            a=THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            app.xx=[0. 0.5 1]*app.L;
            nxx=length(app.xx);
            
            sz=size(app.ModeShapes);
            app.pdof=sz(1);
            
            ev=zeros(3,sz(2));
            
            if(app.BC==1)
                app.mid_dof=floor(app.pdof/2);
                ev(3,:)=app.ModeShapes(end-1,:);
            end
            if(app.BC==2)
                app.mid_dof=round(app.pdof/2);
            end
            if(app.BC==3)
                app.mid_dof=round(app.pdof/2);
            end
            ev(2,:)=app.ModeShapes(app.mid_dof,:);  
            
            accel_abssum=zeros(nxx,1);
              accel_srss=zeros(nxx,1);
              
            pv_abssum=zeros(nxx,1);
              pv_srss=zeros(nxx,1);  
            
            rd_abssum=zeros(nxx,1);
              rd_srss=zeros(nxx,1);
            
            for i=1:nxx

                for j=1:num_fn
                    
                    PFM=app.part(j)*ev(i,j);
                    % fprintf(' i=%d  j=%d  PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,PFM,j,a_srs(j));
                    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            disp(' ');
            disp(' Acceleration');
            
            if(app.iu==1)
                out_a='     x(in)   SRSS(G)  Abssum(G)';
            else
                out_a='     x(m)    SRSS(G)  Abssum(G)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),accel_srss(i),accel_abssum(i));
            end
            
            disp(' ');
            disp(' Pseudo Velocity');
            
            if(app.iu==1)
                out_a='     x(in) SRSS(in/sec)  Abssum(in/sec)';
            else
                out_a='     x(m)  SRSS(cm/sec)  Abssum(cm/sec)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),pv_srss(i),pv_abssum(i));
            end
            
            disp(' ');
            disp(' Relative Displacement');
                
            if(app.iu==1)
                out_a='       x(in)  SRSS(in)  Abssum(in)';
            else
                out_a='       x(m)   SRSS(mm)  Abssum(mm)';   
            end
                
            disp(' ');
            disp(out_a);
            
            for i=1:nxx
                fprintf('     %7.3f  %8.4g  %8.4g \n',app.xx(i),rd_srss(i),rd_abssum(i));
            end

            msgbox('Results written to Command Window');
            
        end

        % Callback function
        function CalculateAppliedForceLengthFRFButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end            
           
        end

        % Button pushed function: 
        % CalculateNaturalFrequenciesPartitionButton
        function CalculateNaturalFrequenciesPartitionButtonPushed(app, event)
            
            CloseFigures(app);
        
            fprintf('\n * * * * * * * * * \n\n');
        
            app.L=app.LengthinEditField.Value;
            app.E=app.ElasticModuluspsiEditField.Value;
            app.rho=app.MassDensitylbmin3EditField.Value;
       
            app.rho=app.rho/386;
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=3;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=3;                
            end    
            
            if((app.LBC==2 && app.RBC==3) || (app.LBC==3 && app.RBC==2)) % pinned-free
                warndlg('case unavailable');
                return;
            end
    
            %%%%%%%%%%%%%%%%%%%%%
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=app.EditField_1.Value;
                thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=app.EditField_1.Value;
                wall_thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=app.EditField_1.Value;
                [area,app.MOI,app.cna]=cylinder_geometry(diameter);     
            end
            if(contains(n,'I-Beam'))
                B=app.EditField_1Label.Value;
                H=app.EditField_2Label.Value;
                Tf=app.EditField_3Label.Value;     
                Tw=app.EditField_4Label.Value;  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=app.EditField_1.Value;
                app.MOI=app.EditField_2.Value;
                app.cna=app.EditField_3.Value;       
            end
            
            nsm=app.UniformNSMEditField.Value;
            nsm=nsm/386;
            
            rho_mpL=app.rho*area + nsm/app.L;   % mass per unit length
            mass=rho_mpL*app.L;
            app.sq_mass=sqrt(mass);

            fprintf('\n area=%8.4g in^2  \t mass=%8.4g lbf sec^2/in \t MOI=%8.4g in^4 \n\n',area,mass,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%
            
            nsn=str2num(app.NumberofElementsListBox.Value);
            app.ne=nsn;

            deltax=app.L/app.ne;
            
            app.dx=zeros(app.ne,1);
            
            for i=1:app.ne
                app.dx(i)=deltax;
            end
            
            app.xx=zeros((app.ne+1),1);
            
            for i=2:(app.ne+1)
               app.xx(i)=app.xx(i-1)+deltax; 
            end
            
            app.dof=2*app.ne+2;
   
            [klocal] = local_stiffness(app.E,app.MOI,app.ne,app.dx);
            [mlocal] = local_mass(rho_mpL,app.ne,app.dx);
            
            kk=zeros(4,4);
            mm=zeros(4,4);
            
            for i=1:4
                for j=1:4
                    kk(i,j)=klocal(1,i,j);
                    mm(i,j)=mlocal(1,i,j);
                end
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                      
            npm=str2num(app.NumberofPointMassesListBox.Value);
            
            pmloc=zeros(1,1);
            pm=zeros(1,1);
            
            if(npm==1)
                   pm(1)=app.PointMasslbmEditField.Value;
                pmloc(1)=app.PointMassLocationfromLeftEndinEditField.Value;
            end
            
            if(app.iu==1) % English
                pm=pm/386;
            end  
                
            [app.global_stiff,app.global_mass,app.global_stiff_unc,app.global_mass_unc,app.dof,app.dof_status,XL,ivector]=...
                         beam_assembly_unc_alt(app.ne,klocal,mlocal,app.LBC,app.RBC,app.dof,npm,pmloc,pm,app.xx);     
             
            if(app.ne==1)
                app.global_stiff
                app.global_mass
            end
                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(npm>=1)
             
                disp(' ');
                disp(' Point mass ');
             
               for i=1:npm
            
                    if(app.iu==1) % English   
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i)*386);
                    else
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i));       
                    end
               
               end
            end
                                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            
            pms=sum(pm);
                
            tmass=mass+pms;
                
            if(app.iu==1) % English    
                fprintf('\n Total mass = %8.4g lbm \n',tmass*386);
            else
                fprintf('\n Total mass = %8.4g kg \n',tmass);        
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.global_stiff,app.global_mass,2);
            
            [app.part]=beam_fn_pf_emm(app.iu,tmass,app.dof,app.fn,app.global_mass,app.ModeShapes,app.MST,app.dof_status,ivector);  % function
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.fn=fix_size(app.fn);
            
            dtype=2;
   
            etype=1;  % enforced acceleration     
            
            [pmass,pstiff,app.nem,app.num,app.nff,ea,app.ngw,app.pdof,app.mid_dof]=...
                               beam_partition(app.BC,app.global_mass_unc,app.global_stiff_unc);
            
            [app.TT,~,~,app.Mwd,app.Mww,~,app.Kww]=enforced_partition_matrices(app.num,ea,pmass,pstiff,etype,dtype);

            [~,app.pomegan,app.pomn2,app.pModeShapes,app.pMST,~]=enforced_acceleration_eigenvalues(app.Kww,app.Mww,app.num,app.nff);
            
            disp(' ');
            disp(' Output arrays saved as: ');
            disp(' ');
            disp('    beam_fn ');
            disp('    beam_pf ');
            disp('    beam_modeshapes ');
            
            disp(' ');
            disp(' Mass & Stiffness arrays saved as:  ');
            disp(' ');
            disp('    beam_mass');
            disp('    beam_stiffness');
            
            assignin('base', 'beam_fn', app.fn);
            assignin('base', 'beam_pf', app.part);
            assignin('base', 'beam_modeshapes', app.ModeShapes);
            
            assignin('base', 'beam_stiffness', app.global_stiff);
            assignin('base', 'beam_mass', app.global_mass);

            num_fn=length(app.fn);
            
            app.s1='a';
            app.s2='a';
            app.s3='a';
            app.s4='a';
            app.s5='a';
            app.s6='a';
            
            app.s1=sprintf('%8.4g',app.fn(1));
            sss={app.s1};  
            
            if(num_fn>=2)
                app.s2=sprintf('%8.4g',app.fn(2));
                sss={app.s1;app.s2};  
            end
            if(num_fn>=3)
                app.s3=sprintf('%8.4g',app.fn(3));
                sss={app.s1;app.s2;app.s3};
            end
            if(num_fn>=4)
                app.s4=sprintf('%8.4g',app.fn(4));
                sss={app.s1;app.s2;app.s3;app.s4};
            end
            if(num_fn>=5)
                app.s5=sprintf('%8.4g',app.fn(5));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5};
            end
            if(num_fn>=6)
                app.s6=sprintf('%8.4g',app.fn(6));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5;app.s6};
            end
            
            app.SelectModeforPlotListBox.Items=sss;
            
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
            ss=sprintf('Select Mode for Plot \n fn (Hz)');
            app.SelectModeforPlotListBoxLabel.Text=ss;
            app.PlotModeShapeButton.Enable='on';
            
            msgbox('Natural Frequencies written to Command Window');            

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            ChangeCrossSection(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=8;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6';'Mode 7';'Mode 8'};
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(i)=v;
                    end            
                end   
            end
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            try
                close Figure 1;
            catch
            end 
    
            n=app.SelectModeforPlotListBox.Value;
            
            if(contains(n,app.s1))
                i=1;
            end
            if(contains(n,app.s2))
                i=2;
            end
            if(contains(n,app.s3))
                i=3;
            end            
            if(contains(n,app.s4))
                i=4;
            end
            if(contains(n,app.s5))
                i=5;
            end
            if(contains(n,app.s6))
                i=6;
            end  
            
            UnityModeShapes=app.ModeShapes(:,i);
            
            figure(1);
            
            num=50;
            
            xq=zeros(num*app.ne,1);
            yq=zeros(num*app.ne,1);
            
            kv=1;

            for j=1:app.ne
   
                if(j==1)
                    if(app.LBC==1)  % fixed
                        t1=0;
                        r1=0;
                        t2=UnityModeShapes(1);
                        r2=UnityModeShapes(2);
                        kt=1;
                        kr=2;
                    end
                    if(app.LBC==2)  % pinned
                        t1=0;
                        r1=UnityModeShapes(1);
                        t2=UnityModeShapes(2);
                        r2=UnityModeShapes(3);
                        kt=2;
                        kr=3;                        
                    end    
                    if(app.LBC==3)   % free
                        t1=UnityModeShapes(1);
                        r1=UnityModeShapes(2);
                        t2=UnityModeShapes(3);
                        r2=UnityModeShapes(4);
                        kt=3;
                        kr=4;                        
                    end   
                end    
                if(j>=2 && j<=app.ne-1)
                    t1=UnityModeShapes(kt);
                    r1=UnityModeShapes(kr);
                    kt=kt+2;
                    kr=kr+2;   
                    t2=UnityModeShapes(kt);
                    r2=UnityModeShapes(kr);
                end

                if(j==app.ne && j>=2)
                    if(app.RBC==1)  % fixed
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=0;
                    end
                    if(app.RBC==2)  % pinned
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=UnityModeShapes(end);                    
                    end    
                    if(app.RBC==3)   % free
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=UnityModeShapes(kt+2);
                        r2=UnityModeShapes(kr+2);                    
                    end   
                end                    
                
                h=app.dx(j);
                delta=1/(num-1);
                
                for jk=1:num
                    de=(jk-1)*delta;
                    e=1-de;
                    c1=3*e^2-2*e^3;
                    c2=e^2-e^3;
                    c3=1-3*e^2+2*e^3;
                    c4=-e+2*e^2-e^3;
                    yq(kv)=c1*t1 +c2*h*r1 +c3*t2 + c4*h*r2;
                    xe=h*de;
                    xq(kv)=xe+app.xx(j);
                    % fprintf('j=%d jk=%d kv=%d xq=%6.4g yq=%6.4g \n',j,jk,kv,xq(kv),yq(kv));
                    kv=kv+1;
                end            
            end
            
            yq=yq/(max(abs(yq)));
            plot(xq,yq,'b');    
                
            grid on;
            axis([0,app.L,-1.1,1.1]);
            xlabel('x (inch) ');
            ylabel('Normalized Modal displacement');
            sss=sprintf('Mode %d   fn=%8.4g Hz  with Cubic Interpolation',i,app.fn(i));
            title(sss)

            
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end

        % Button pushed function: SaveTransButton
        function SaveTransButtonButtonPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_Trans.Value);
            if(contains(n,'Acceleration'))
                data=app.atrans;
            end
            if(contains(n,'Displacement'))
                data=app.rdtrans;
            end
            if(contains(n,'Stress'))
                data=app.strans;
            end 
          
            output_name=strtrim(app.OutputArrayNameEditField_Trans.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');            
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFrequencyHzEditField_sine.Value=app.fn(1);
        end

        % Button pushed function: SaveButton_arbitrary
        function SaveButton_arbitraryPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_arbitrary.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            output_name=strtrim(app.OutputArrayNameEditField_arbitrary.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');         
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_PSD.Value);
            
            if(contains(n,'Relative Displacement Trans'))
                data=app.rd_response_trans;
            end
            if(contains(n,'Relative Displacement PSD'))
                data=app.rd_response_psd;
            end            
            if(contains(n,'Acceleration Trans'))
                data=app.acc_response_trans;
            end
            if(contains(n,'Acceleration PSD'))
                data=app.acc_response_psd;
            end                 
            if(contains(n,'Bending Stress Trans'))
                data=app.stress_response_trans;
            end
            if(contains(n,'Bending Stress PSD'))
                data=app.stress_response_psd;
            end             
            
            output_name=strtrim(app.OutputArrayNameEditField_PSD.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');       
        end

        % Value changed function: NumberofModesListBox_HS
        function NumberofModesListBox_HSValueChanged(app, event)
            app.SaveButton_HS.Enable='off';
        end

        % Value changed function: BaseInputAccelGEditField
        function BaseInputAccelGEditFieldValueChanged(app, event)

            app.SaveButton_HS.Enable='off';
        end

        % Value changed function: PulseDurationsecEditField
        function PulseDurationsecEditFieldValueChanged(app, event)

            app.SaveButton_HS.Enable='off';
        end

        % Callback function
        function ResponseLocationEditField_HSValueChanged(app, event)

            app.SaveButton_HS.Enable='off';            
        end

        % Button pushed function: SaveButton_HS
        function SaveButton_HSPushed(app, event)
            output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
            n=strtrim(app.OutputFunctionListBox_HS.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Value changed function: NumberofPointMassesListBox
        function NumberofPointMassesListBoxValueChanged(app, event)
            PointMass(app);
        end

        % Selection changed function: TabsLocationButtonGroup
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIBeamBendingFEAUIFigure = uifigure('Visible', 'off');
            app.UIBeamBendingFEAUIFigure.Name = 'UI Beam Bending FEA';
            app.TabGroup = uitabgroup(app.UIBeamBendingFEAUIFigure);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'numeric');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Fixed';
            app.NumberofElementsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.NumberofElementsListBoxLabel.Text = 'Number of Elements';
            app.NumberofElementsListBox = uilistbox(app.GeometryBCsTab);
            app.NumberofElementsListBox.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512'};
            app.NumberofElementsListBox.Value = '1';
            app.MaterialAddedMassTab = uitab(app.TabGroup);
            app.MaterialAddedMassTab.Title = 'Material & Added Mass';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.MaterialLabel = uilabel(app.MaterialAddedMassTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialAddedMassTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other', ''};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNSMEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.NumberofPointMassesListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBoxLabel.Text = 'Number of Point Masses';
            app.NumberofPointMassesListBox = uilistbox(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBox.Items = {'0', '1'};
            app.NumberofPointMassesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPointMassesListBoxValueChanged, true);
            app.NumberofPointMassesListBox.Value = '0';
            app.PointMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMasslbmEditFieldLabel.Text = 'Point Mass (lbm)';
            app.PointMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.PointMassLocationfromLeftEndinEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMassLocationfromLeftEndinEditFieldLabel.Text = 'Point Mass Location from Left End (in)';
            app.PointMassLocationfromLeftEndinEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.CalculateNaturalFrequenciesPartitionButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequenciesPartitionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesPartitionButtonPushed, true);
            app.CalculateNaturalFrequenciesPartitionButton.Text = 'Calculate Natural Frequencies & Partition';
            app.PlotModeShapeButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFrequenciesTab);
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {''};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.BaseInputTransTab = uitab(app.TabGroup);
            app.BaseInputTransTab.Title = 'Base Input Trans';
            app.CalculateBaseInputTrans = uibutton(app.BaseInputTransTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.StartFrequencyHzEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.StartFrequencyHzEditFieldLabel.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.BaseInputTransTab, 'numeric');
            app.EndFrequencyHzEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.EndFrequencyHzEditFieldLabel.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.BaseInputTransTab, 'numeric');
            app.NumberofModesListBoxLabel = uilabel(app.BaseInputTransTab);
            app.NumberofModesListBoxLabel.Text = 'Number of Modes';
            app.NumberofModesListBox = uilistbox(app.BaseInputTransTab);
            app.NumberofModesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox.Value = '1';
            app.SaveTransButton = uibutton(app.BaseInputTransTab, 'push');
            app.SaveTransButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransButtonButtonPushed, true);
            app.SaveTransButton.Text = 'Save';
            app.OutputFunctionListBoxLabel = uilabel(app.BaseInputTransTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox_Trans = uilistbox(app.BaseInputTransTab);
            app.OutputFunctionListBox_Trans.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.OutputArrayNameEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField_Trans = uieditfield(app.BaseInputTransTab, 'text');
            app.ImageBeamBase = uiimage(app.BaseInputTransTab);
            app.TransmissibilityforBaseExcitationLabel = uilabel(app.BaseInputTransTab);
            app.TransmissibilityforBaseExcitationLabel.Text = 'Transmissibility for Base Excitation';
            app.BaseInputSineTab = uitab(app.TabGroup);
            app.BaseInputSineTab.Title = 'Base Input Sine';
            app.CalculateBaseInputSine = uibutton(app.BaseInputSineTab, 'push');
            app.CalculateBaseInputSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSineButtonPushed, true);
            app.CalculateBaseInputSine.Text = 'Calculate';
            app.ExcitationFrequencyHzLabel = uilabel(app.BaseInputSineTab);
            app.ExcitationFrequencyHzLabel.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzEditField_sine = uieditfield(app.BaseInputSineTab, 'numeric');
            app.BaseAccelerationGEditFieldLabel = uilabel(app.BaseInputSineTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.BaseInputSineTab, 'numeric');
            app.NumberofModesListBox_2Label = uilabel(app.BaseInputSineTab);
            app.NumberofModesListBox_2Label.Text = 'Number of Modes';
            app.NumberofModesListBox_sine = uilistbox(app.BaseInputSineTab);
            app.NumberofModesListBox_sine.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_sine.Value = '1';
            app.SettoFirstfnButton = uibutton(app.BaseInputSineTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.ImageBeamBase_sine = uiimage(app.BaseInputSineTab);
            app.BaseInputHalfSineTab = uitab(app.TabGroup);
            app.BaseInputHalfSineTab.Title = 'Base Input Half-Sine';
            app.CalculateBaseInput_HS = uibutton(app.BaseInputHalfSineTab, 'push');
            app.CalculateBaseInput_HS.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInput_HSButtonPushed, true);
            app.CalculateBaseInput_HS.Text = 'Calculate';
            app.ImageBeamBase_HS = uiimage(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_2Label_2 = uilabel(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_2Label_2.Text = 'Number of Modes';
            app.NumberofModesListBox_HS = uilistbox(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_HS.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_HS.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBox_HSValueChanged, true);
            app.NumberofModesListBox_HS.Value = '1';
            app.ResponsetoHalfSineBaseInputLabel = uilabel(app.BaseInputHalfSineTab);
            app.ResponsetoHalfSineBaseInputLabel.Text = 'Response to Half-Sine Base Input';
            app.BaseInputAccelGEditFieldLabel = uilabel(app.BaseInputHalfSineTab);
            app.BaseInputAccelGEditFieldLabel.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.BaseInputAccelGEditField.ValueChangedFcn = createCallbackFcn(app, @BaseInputAccelGEditFieldValueChanged, true);
            app.PulseDurationsecLabel = uilabel(app.BaseInputHalfSineTab);
            app.PulseDurationsecLabel.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.PulseDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationsecEditFieldValueChanged, true);
            app.SaveButton_HS = uibutton(app.BaseInputHalfSineTab, 'push');
            app.SaveButton_HS.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_HSPushed, true);
            app.SaveButton_HS.Text = 'Save';
            app.OutputFunctionListBox_2Label_3 = uilabel(app.BaseInputHalfSineTab);
            app.OutputFunctionListBox_2Label_3.Text = 'Output Function';
            app.OutputFunctionListBox_HS = uilistbox(app.BaseInputHalfSineTab);
            app.OutputFunctionListBox_HS.Items = {'Absolute Acceleration', 'Relative Displacement'};
            app.OutputFunctionListBox_HS.Value = 'Absolute Acceleration';
            app.OutputArrayNameEditField_2Label_3 = uilabel(app.BaseInputHalfSineTab);
            app.OutputArrayNameEditField_2Label_3.Text = 'Output Array Name';
            app.OutputArrayNameEditField_HS = uieditfield(app.BaseInputHalfSineTab, 'text');
            app.TotalResponseDurationsecEditFieldLabel = uilabel(app.BaseInputHalfSineTab);
            app.TotalResponseDurationsecEditFieldLabel.Text = 'Total Response Duration (sec)';
            app.TotalResponseDurationsecEditField = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.BaseInputPSDTab = uitab(app.TabGroup);
            app.BaseInputPSDTab.Title = 'Base Input PSD';
            app.CalculateBaseInputPSD = uibutton(app.BaseInputPSDTab, 'push');
            app.CalculateBaseInputPSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputPSDButtonPushed, true);
            app.CalculateBaseInputPSD.Text = 'Calculate';
            app.ImageBeamBase_PSD = uiimage(app.BaseInputPSDTab);
            app.NumberofModesListBox_2Label_3 = uilabel(app.BaseInputPSDTab);
            app.NumberofModesListBox_2Label_3.Text = 'Number of Modes';
            app.NumberofModesListBox_PSD = uilistbox(app.BaseInputPSDTab);
            app.NumberofModesListBox_PSD.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_PSD.Value = '1';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.BaseInputPSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.InputArrayNameEditField_2Label = uilabel(app.BaseInputPSDTab);
            app.InputArrayNameEditField_2Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_PSD = uieditfield(app.BaseInputPSDTab, 'text');
            app.SaveButton_PSD = uibutton(app.BaseInputPSDTab, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputFunctionListBox_2Label_2 = uilabel(app.BaseInputPSDTab);
            app.OutputFunctionListBox_2Label_2.Text = 'Output Function';
            app.OutputFunctionListBox_PSD = uilistbox(app.BaseInputPSDTab);
            app.OutputFunctionListBox_PSD.Items = {'Relative Displacement', 'Relative Velocity', 'Absolute Acceleration', 'Bending Moment', 'Bending Stress', 'Shear Force'};
            app.OutputFunctionListBox_PSD.Value = 'Relative Displacement';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.BaseInputPSDTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.BaseInputPSDTab, 'text');
            app.BaseInputArbitraryTab = uitab(app.TabGroup);
            app.BaseInputArbitraryTab.Title = 'Base Input Arbitrary';
            app.CalculateBaseInputArbitrary = uibutton(app.BaseInputArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitraryButtonPushed, true);
            app.CalculateBaseInputArbitrary.Text = 'Calculate';
            app.NumberofModesListBox_3Label = uilabel(app.BaseInputArbitraryTab);
            app.NumberofModesListBox_3Label.Text = 'Number of Modes';
            app.NumberofModesListBox_arbitrary = uilistbox(app.BaseInputArbitraryTab);
            app.NumberofModesListBox_arbitrary.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofModesListBox_arbitrary.Value = '1';
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel = uilabel(app.BaseInputArbitraryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.InputArrayNameEditFieldLabel = uilabel(app.BaseInputArbitraryTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField_arbitrary = uieditfield(app.BaseInputArbitraryTab, 'text');
            app.ImageBeamBase_arbitrary = uiimage(app.BaseInputArbitraryTab);
            app.SaveButton_arbitrary = uibutton(app.BaseInputArbitraryTab, 'push');
            app.SaveButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_arbitraryPushed, true);
            app.SaveButton_arbitrary.Text = 'Save';
            app.OutputFunctionListBox_2Label = uilabel(app.BaseInputArbitraryTab);
            app.OutputFunctionListBox_2Label.Text = 'Output Function';
            app.OutputFunctionListBox_arbitrary = uilistbox(app.BaseInputArbitraryTab);
            app.OutputFunctionListBox_arbitrary.Items = {'Absolute Acceleration', 'Relative Velocity', 'Relative Displacement', 'Bending Stress'};
            app.OutputFunctionListBox_arbitrary.Value = 'Absolute Acceleration';
            app.OutputArrayNameEditField_2Label = uilabel(app.BaseInputArbitraryTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_arbitrary = uieditfield(app.BaseInputArbitraryTab, 'text');
            app.DetrendDisplacementListBoxLabel = uilabel(app.BaseInputArbitraryTab);
            app.DetrendDisplacementListBoxLabel.Text = 'Detrend Displacement';
            app.DetrendDisplacementListBox = uilistbox(app.BaseInputArbitraryTab);
            app.DetrendDisplacementListBox.Items = {'Yes', 'No'};
            app.DetrendDisplacementListBox.Value = 'Yes';
            app.BaseInputMDOFSRSTab = uitab(app.TabGroup);
            app.BaseInputMDOFSRSTab.Title = 'Base Input MDOF SRS';
            app.CalculateBaseInputMDOFSRS = uibutton(app.BaseInputMDOFSRSTab, 'push');
            app.CalculateBaseInputMDOFSRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputMDOFSRSButtonPushed, true);
            app.CalculateBaseInputMDOFSRS.Text = 'Calculate';
            app.ImageBeamBase_MDOFSRS = uiimage(app.BaseInputMDOFSRSTab);
            app.NumberofModesListBox_3Label_2 = uilabel(app.BaseInputMDOFSRSTab);
            app.NumberofModesListBox_3Label_2.Text = 'Number of Modes';
            app.NumberofModesListBox_MDOFSRS = uilistbox(app.BaseInputMDOFSRSTab);
            app.NumberofModesListBox_MDOFSRS.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_MDOFSRS.Value = '1';
            app.SpecificationQEditFieldLabel = uilabel(app.BaseInputMDOFSRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.BaseInputMDOFSRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.BaseInputMDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)'; ''};
            app.InputArrayNameEditField_2Label_2 = uilabel(app.BaseInputMDOFSRSTab);
            app.InputArrayNameEditField_2Label_2.Text = 'Input Array Name';
            app.InputArrayNameEditField_MDOFSRS = uieditfield(app.BaseInputMDOFSRSTab, 'text');
            app.ReturnButton = uibutton(app.UIBeamBendingFEAUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.beam_bending_aver10byTomIrvineLabel = uilabel(app.UIBeamBendingFEAUIFigure);
            app.beam_bending_aver10byTomIrvineLabel.Text = {'beam_bending_FEA_a    ver 1.3  by Tom Irvine      This script calculates the natural frequencies of a beam and its response to excitation via FEA.'; ''; ''};
            app.TabsLocationButtonGroup = uibuttongroup(app.UIBeamBendingFEAUIFigure);
            app.TabsLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TabsLocationButtonGroupSelectionChanged, true);
            app.TabsLocationButtonGroup.Title = 'Tabs Location';
            app.TopButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.TopButton.Text = 'Top';
            app.RightButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            % Show the figure after all components are created
            app.UIBeamBendingFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beam_bending_FEA_aa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIBeamBendingFEAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIBeamBendingFEAUIFigure)
        end
    end
end