classdef beam_bending_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamBendingContinuousModelUIFigure  matlab.ui.Figure
        beam_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.EditField
        EditField_1Label                matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        ImageIBeam                      matlab.ui.control.Image
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MaterialTab                     matlab.ui.container.Tab
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3Label          matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialLabel                   matlab.ui.control.Label
        NaturalFreqTab                  matlab.ui.container.Tab
        MassResultsTextArea             matlab.ui.control.TextArea
        MassResultsTextAreaLabel        matlab.ui.control.Label
        UITable_fn                      matlab.ui.control.Table
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        PlotModeShapeButton             matlab.ui.control.Button
        CalculateNaturalFrequenciesButton  matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        BaseExcitationTab               matlab.ui.container.Tab
        Rainflow                        matlab.ui.control.Button
        BeamLengthinEditField           matlab.ui.control.NumericEditField
        BeamLengthinEditFieldLabel      matlab.ui.control.Label
        ResponseLocationinEditField_base  matlab.ui.control.EditField
        ResponseLocationinLabel_base    matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        OutputArrayNameEditField_4      matlab.ui.control.EditField
        OutputArrayNameEditField_4Label  matlab.ui.control.Label
        OutputFunctionListBox_4         matlab.ui.control.ListBox
        OutputFunctionListBox_4Label    matlab.ui.control.Label
        SaveTransButton_2               matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        TransTab                        matlab.ui.container.Tab
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        EndFrequencyHzEditFieldLabel    matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        StartFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CalculateBaseInputTrans         matlab.ui.control.Button
        SineTab                         matlab.ui.container.Tab
        CalculateBaseInputSine_2        matlab.ui.control.Button
        BaseAccelGEditField             matlab.ui.control.EditField
        BaseAccelGEditFieldLabel        matlab.ui.control.Label
        ExcitationFrequencyHzEditField  matlab.ui.control.EditField
        ExcitationFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SettoFirstfnButton_2            matlab.ui.control.Button
        HalfSineTab                     matlab.ui.container.Tab
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        BaseInputAccelGEditField        matlab.ui.control.EditField
        BaseInputAccelGLabel            matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PSDTab                          matlab.ui.container.Tab
        UpdateListButton_psd_3          matlab.ui.control.Button
        SelectInputArrayListBox_psd_2   matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_3  matlab.ui.control.Label
        UpdateListButton_psd_2          matlab.ui.control.Button
        text1_psd_2                     matlab.ui.control.Label
        listbox_method_psd_2            matlab.ui.control.ListBox
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel_2  matlab.ui.control.Label
        ArbitraryTab                    matlab.ui.container.Tab
        TheinputarraymusthavetwocolumnsTimesecAccelGLabel  matlab.ui.control.Label
        SelectInputArrayListBox_3       matlab.ui.control.ListBox
        SelectInputArrayListBox_3Label  matlab.ui.control.Label
        UpdateListButton_2              matlab.ui.control.Button
        text1_2                         matlab.ui.control.Label
        listbox_method_2                matlab.ui.control.ListBox
        CalculateBaseInputArbitrary_2   matlab.ui.control.Button
        MDOFSRSTab                      matlab.ui.container.Tab
        SpecificationQEditField_2       matlab.ui.control.EditField
        SpecificationQEditField_2Label  matlab.ui.control.Label
        UpdateListButton_srs_3          matlab.ui.control.Button
        SelectInputArrayListBox_srs_2   matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_4  matlab.ui.control.Label
        UpdateListButton_srs_2          matlab.ui.control.Button
        text1_srs_2                     matlab.ui.control.Label
        listbox_method_srs_2            matlab.ui.control.ListBox
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel_2  matlab.ui.control.Label
        ImageBeamBase_2                 matlab.ui.control.Image
        NumberofModesListBox_base       matlab.ui.control.ListBox
        NumberofModesLabel              matlab.ui.control.Label
        AppliedPointForceTab            matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        ResultsTextArea_force           matlab.ui.control.TextArea
        ResultsTextArea_forceLabel      matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        TransferFunctionTab             matlab.ui.container.Tab
        EndFrequencyHzEditField_2       matlab.ui.control.NumericEditField
        EndFrequencyHzEditField_2Label  matlab.ui.control.Label
        StartFrequencyHzEditField_2     matlab.ui.control.NumericEditField
        StartFrequencyHzEditField_2Label  matlab.ui.control.Label
        CalculateAppliedForce           matlab.ui.control.Button
        SineTimeHistoryTab              matlab.ui.container.Tab
        SampleRateEditField_force       matlab.ui.control.EditField
        DurationsecEditField_2Label_3   matlab.ui.control.Label
        SetfnButton                     matlab.ui.control.Button
        FreqEditField                   matlab.ui.control.EditField
        DurationsecEditField_2Label_2   matlab.ui.control.Label
        CalculateButton_force_sine      matlab.ui.control.Button
        DurationsecEditField_force_sine  matlab.ui.control.EditField
        DurationsecEditField_2Label     matlab.ui.control.Label
        ForcelbfEditField               matlab.ui.control.EditField
        ForcelbfEditFieldLabel          matlab.ui.control.Label
        ArbitraryTimeHistoryTab         matlab.ui.container.Tab
        UpdateListButton_arb_force      matlab.ui.control.Button
        TimesecForcelbfLabel            matlab.ui.control.Label
        TheinputarraymusthavetwocolumnsLabel  matlab.ui.control.Label
        SelectInputArrayListBox_arb_force  matlab.ui.control.ListBox
        SelectInputArrayListBox_5Label  matlab.ui.control.Label
        text1_3                         matlab.ui.control.Label
        listbox_method_arb_force        matlab.ui.control.ListBox
        CalculateAppliedForce_2         matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        OutputArrayNameEditField_force  matlab.ui.control.EditField
        OutputArrayNameEditField_5Label  matlab.ui.control.Label
        OutputFunctionListBox_force     matlab.ui.control.ListBox
        OutputFunctionListBox_5Label    matlab.ui.control.Label
        Rainflow_2                      matlab.ui.control.Button
        SaveTransButton_3               matlab.ui.control.Button
        NumberofModesListBox_pforce     matlab.ui.control.ListBox
        NumberofModesListBox_5Label     matlab.ui.control.Label
        AppliedDistributedForceTab      matlab.ui.container.Tab
        OutputArrayNameEditField_dforce  matlab.ui.control.EditField
        OutputArrayNameEditField_dforce_Label  matlab.ui.control.Label
        OutputFunctionListBox_dforce    matlab.ui.control.ListBox
        OutputFunctionListBox_dforce_Label  matlab.ui.control.Label
        SaveTransButton_dforce          matlab.ui.control.Button
        ResultsTextArea_dforce          matlab.ui.control.TextArea
        ResultsTextArea_dforce_Label    matlab.ui.control.Label
        TabGroup2_2                     matlab.ui.container.TabGroup
        TransTab_2                      matlab.ui.container.Tab
        EndFrequencyHzEditField_dforce  matlab.ui.control.NumericEditField
        EndFrequencyHzEditField_3Label  matlab.ui.control.Label
        StartFrequencyHzEditField_dforce  matlab.ui.control.NumericEditField
        StartFrequencyHzEditField_3Label  matlab.ui.control.Label
        CalculateBaseInputTrans_dforce  matlab.ui.control.Button
        PressurePSDTab                  matlab.ui.container.Tab
        Calculate_psd_dforce            matlab.ui.control.Button
        SelectInputArrayListBox_psd_dforce  matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_5  matlab.ui.control.Label
        UpdateListButton_psd_dforce     matlab.ui.control.Button
        text1_psd_3                     matlab.ui.control.Label
        listbox_method_psd_dforce       matlab.ui.control.ListBox
        Pressurepsi2HzLabel             matlab.ui.control.Label
        BeamLengthinEditField_dforce    matlab.ui.control.NumericEditField
        BeamLengthinEditField_dforce_Label  matlab.ui.control.Label
        ResponseLocationinEditField_dforce  matlab.ui.control.EditField
        ResponseLocationinEditField_dforce_Label  matlab.ui.control.Label
        NumberofModesListBox_dforce     matlab.ui.control.ListBox
        NumberofModesListBox_5Label_2   matlab.ui.control.Label
    end

    
    properties (Access = private)
        L   % Length
        E   % Face Sheet elastic modulus
        rho % Face Sheet mass density
        fn  % Natural frequencies
        dm  % Damping
        omegan % Angular natural frequencies
        ModeShapes % ModeShapes
        cna % Distance to neutral axis
        x   % x length along beam for mode shapes
        y   % Mode shape
        xx   % x length along beam for mode shapes
        ZZ   % Mode shape
        sq_mass % sqrt of mass
        MOI % Area moment of intertia
        C   % Modal constant
        beta % eigenvalue parameter
        part % participation factor
        s1  % modal frequency 1
        s2  % modal frequency 2
        s3  % modal frequency 3
        s4  % modal frequency 4
        s5  % modal frequency 5
        s6  % modal frequency 6
        RBC % Right boundary condition
        LBC % Left boundary condition
        bending_stress % 
        shear_force    % 
        bending_moment % 
        absolute_accel % 
        relative_vel   % 
        relative_disp  %   
        acceleration   %
        velocity       %
        displacement   %
        relative_velocity     %
        relative_displacement %   
        acc_response_psd %
	    rv_response_psd  %
        rd_response_psd  %
        moment_response_psd %            
        shear_response_psd  %
        stress_response_psd %
        kflag %
        THM %
        iu %
        imat %
        number_modes %
        analysis %
        bc_type  %
        cwidth   %
        mass_per_length %
        yy  %
    end
    
    methods (Access = private)

        function force_time(app)
            mass=app.sq_mass^2;

            app.number_modes=str2num(app.NumberofModesListBox_pforce.Value);

            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            bbeta=app.beta(1:app.number_modes);
            CC=app.C(1:app.number_modes);
            
            [app.acceleration,app.velocity,app.displacement,app.bending_stress]=...
                fixed_free_point_force_end_fth(app.THM,ffn,ddm,mass,app.L,bbeta,CC,app.iu,app.E,app.MOI,app.cna);

            sq={'Acceleration free end','Velocity free end','Displacement free end','Bending Stress fixed end'};
            app.OutputFunctionListBox_force.Items=sq;
           
            
            ss=(' Peak Response at Beam Free End ');
            
            ss=sprintf('%s \n\n  Acceleration = %8.4g G ',ss,max(abs(app.acceleration(:,2))));
            
            if(app.iu==1)
	            out2=sprintf('\n      Velocity = %8.4g in/sec ',max(abs(app.velocity(:,2))));
	            out3=sprintf('\n  Displacement = %8.4g in ',max(abs(app.displacement(:,2))));
            else
	            out2=sprintf('\n      Velocity = %8.4g m/sec ',max(abs(app.velocity(:,2))));
	            out3=sprintf('\n  Displacement = %8.4g mm ',max(abs(app.displacement(:,2))));
            end

            ss=sprintf('%s %s %s',ss,out2,out3);
            ss=sprintf('%s \n\n Peak Response at Beam Fixed End \n',ss);
            
            if(app.iu==1)
                out1=sprintf('\n  Bending Stress = %8.4g psi \n',max(abs(app.bending_stress(:,2))));
                out2=sprintf('                          = %8.4g ksi ',(1/1000)*max(abs(app.bending_stress(:,2))));    
                ss=sprintf('%s %s %s',ss,out1,out2);                
            else
                out1=sprintf('\n  Bending Stress = %8.4g Pa ',max(abs(app.bending_stress(:,2))));
                ss=sprintf('%s %s ',ss,out1);
            end

            app.ResultsTextArea_force.Value=ss;
            app.ResultsTextArea_forceLabel.Visible='on';
            app.ResultsTextArea_force.Visible='on';
        end    

        function Unit(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);       

            if(app.iu==1)
                app.LengthinEditFieldLabel.Text='Length (in)';
	            app.BeamLengthinEditFieldLabel.Text=sprintf('Beam \nLength (in)');
                app.BeamLengthinEditField_dforce_Label.Text=sprintf('Beam \nLength (in)');
	            app.ResponseLocationinLabel_base.Text=sprintf('Response \nLocation (in)');  
                app.ResponseLocationinEditField_dforce_Label.Text=sprintf('Response \nLocation (in)');
            else
                app.LengthinEditFieldLabel.Text='Length (m)';
	            app.BeamLengthinEditFieldLabel.Text=sprintf('Beam \nLength (m)');
                app.BeamLengthinEditField_dforce_Label.Text=sprintf('Beam \nLength (m)');
	            app.ResponseLocationinLabel_base.Text=sprintf('Response \nLocation (m)');  
	            app.ResponseLocationinEditField_dforce_Label.Text=sprintf('Response \nLocation (m)');                 
            end 

            ChangeMaterials(app);
        end

        function CloseResults(app)
            CloseFigures(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.SaveTransButton_2.Visible='off';
            app.OutputArrayNameEditField_4.Visible='off';
            app.OutputArrayNameEditField_4Label.Visible='off';
            app.OutputFunctionListBox_4.Visible='off';
            app.OutputFunctionListBox_4Label.Visible='off';
            app.Rainflow.Visible='off';
            app.ResultsTextArea_forceLabel.Visible='off';
            app.ResultsTextArea_force.Visible='off';
            app.ResultsTextArea_dforce_Label.Visible='off';
            app.ResultsTextArea_dforce.Visible='off';  
            app.OutputArrayNameEditField_dforce.Visible='off';
            app.OutputArrayNameEditField_dforce_Label.Visible='off';
            app.OutputFunctionListBox_dforce.Visible='off';
            app.OutputFunctionListBox_dforce_Label.Visible='off';
            app.SaveTransButton_dforce.Visible='off';
        end    

        function OpenSave_dforce(app)
            app.ResultsTextArea_dforce.Visible='on';
            app.ResultsTextArea_dforce_Label.Visible='on';
            app.SaveTransButton_dforce.Visible='on';
            app.OutputArrayNameEditField_dforce.Visible='on';
            app.OutputArrayNameEditField_dforce_Label.Visible='on';
            app.OutputFunctionListBox_dforce.Visible='on';
            app.OutputFunctionListBox_dforce_Label.Visible='on';  
        end            

        function OpenSave(app)
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
            app.SaveTransButton_2.Visible='on';
            app.OutputArrayNameEditField_4.Visible='on';
            app.OutputArrayNameEditField_4Label.Visible='on';
            app.OutputFunctionListBox_4.Visible='on';
            app.OutputFunctionListBox_4Label.Visible='on';           
        end    

        function preprocess_pforce(app)
            CloseResults(app);
            
            app.kflag=1;

            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                app.kflag=999;
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                app.kflag=999;
                return;
            end       

            try
                app.number_modes=str2double(app.NumberofModesListBox_pforce.Value);
            catch
                warndlg('app.number_modes failed');
                app.kflag=999;
                return;
            end
        end 

        function preprocess_dforce(app)
            CloseResults(app);
            
            app.kflag=1;

            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                app.kflag=999;
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                app.kflag=999;
                return;
            end        

            try
                app.xx=str2num(app.ResponseLocationinEditField_dforce.Value);  
            catch
                warndlg('app.xx failed');
                app.kflag=999;
                return; 
            end

            try
                app.number_modes=str2double(app.NumberofModesListBox_dforce.Value);
            catch
                warndlg('app.number_modes failed');
                app.kflag=999;
                return;
            end
        end    

        function preprocess(app)

            CloseResults(app);
            
            app.kflag=1;

            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                app.kflag=999;
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                app.kflag=999;
                return;
            end        

            try
                app.xx=str2num(app.ResponseLocationinEditField_base.Value);  
            catch
                warndlg('app.xx failed');
                app.kflag=999;
                return; 
            end


            try
                app.number_modes=str2double(app.NumberofModesListBox_base.Value);
            catch
                warndlg('app.number_modes failed');
                app.kflag=999;
                return;
            end

        end    

        function update_srs(app)
            CloseFigures(app);
            cell_array=update_shock_response_spectrum();
            try
                app.SelectInputArrayListBox_srs_2.Items=unique(sort(cell_array)); 
                app.SelectInputArrayListBox_srs_2.Value=app.SelectInputArrayListBox_srs_2.Items{1};
            catch
            end
        end            

        function update_psd(app)
            CloseFigures(app);
            [cell_array]=update_power_spectral_density();
            try
                app.SelectInputArrayListBox_psd_2.Items=unique(sort(cell_array)); 
                app.SelectInputArrayListBox_psd_2.Value=app.SelectInputArrayListBox_psd_2.Items{1};
            catch
            end
            try
                app.SelectInputArrayListBox_psd_dforce.Items=unique(sort(cell_array)); 
                app.SelectInputArrayListBox_psd_dforce.Value=app.SelectInputArrayListBox_psd_dforce.Items{1};
            catch
            end            
        end    

        function update_arb(app)

            CloseFigures(app);
            [cell_array]=update_time_history();
           
            try
                app.SelectInputArrayListBox_3.Items=unique(sort(cell_array));
                app.SelectInputArrayListBox_3.Value=app.SelectInputArrayListBox_3.Items{1};
            catch
            end
        end
        function update_arb_force(app)

            CloseFigures(app);
            [cell_array]=update_time_history();
           
            try
                app.SelectInputArrayListBox_arb_force.Items=unique(sort(cell_array));
                app.SelectInputArrayListBox_arb_force.Value=app.SelectInputArrayListBox_arb_force.Items{1};
            catch
            end
        end        
  
        function CloseFigures(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end

        function Clearfn(app)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';            
            app.UITable_fn.Visible='off';
            app.MassResultsTextArea.Visible='off';
            app.MassResultsTextAreaLabel.Visible='off';
            app.PlotModeShapeButton.Visible='off';    
            CloseResults(app);
            app.fn=0;
        end    
        
        function ChangeMaterials(app)

            Clearfn(app);

            Items = char(app.UnitsListBox.Items);

            
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);     

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.ForcelbfEditFieldLabel.Text='Force (lbf)';
                app.MassDensitylbmin3Label.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';    
                app.TimesecForcelbfLabel.Text='Time(sec) & Force(lbf)';
                app.Pressurepsi2HzLabel.Text='The input array must have two columns:  Freq (Hz) & Pressure (psi^2/Hz)';
            else
                app.ForcelbfEditFieldLabel.Text='Force (N)';
                app.MassDensitylbmin3Label.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.TimesecForcelbfLabel.Text='Time(sec) & Force(N)';
                app.Pressurepsi2HzLabel.Text='The input array must have two columns:  Freq (Hz) & Pressure (Pa^2/Hz)';             
            end    
                       
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
           
        end
        
        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style

        end
        
        function ChangeCrossSection(app)

            Clearfn(app);

            Unit(app);

            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Width (in)';
                    app.EditField_2Label.Text='Thickness (in)';
                else
                    app.EditField_1Label.Text='Width (mm)';
                    app.EditField_2Label.Text='Thickness (mm)';                    
                end    

                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                
            end
            if(contains(n,'Pipe'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Outer Diameter (in)';
                    app.EditField_2Label.Text='Wall Thickness (in)';  
                else
                    app.EditField_1Label.Text='Outer Diameter (mm)';
                    app.EditField_2Label.Text='Wall Thickness (mm)'; 
                end
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Diameter (in)';
                else
                    app.EditField_1Label.Text='Diameter (mm)';
                end    
                app.EditField_2.Visible='off';     
                app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Base Width (in)';
                    app.EditField_2Label.Text='Total Height (in)';
                    app.EditField_3Label.Text='Flange Thickness (in)';     
                    app.EditField_4Label.Text='Web Thickness (in)';
                else
                    app.EditField_1Label.Text='Base Width (mm)';
                    app.EditField_2Label.Text='Total Height (mm)';
                    app.EditField_3Label.Text='Flange Thickness (mm)';     
                    app.EditField_4Label.Text='Web Thickness (mm)';
                end    

                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Area (in^2)';
                    app.EditField_2Label.Text='Area moment Inertia (in^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                else
                    app.EditField_1Label.Text='Area (mm^2)';
                    app.EditField_2Label.Text='Area moment Inertia (mm^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (mm)';
                end
                
                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end
        
        function BeamBaseImage(app)
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;  

            app.bc_type=0;

            if((contains(nL,'Fixed') && contains(nR,'Free')) || (contains(nL,'Free') && contains(nR,'Fixed')))  
                app.bc_type=1;
                app.ImageBeamBase_2.Visible='on';
                app.ImageBeamBase_2.ImageSource='beam_bending_base.jpg';
                app.Image2.ImageSource='cant_beam_force.jpg';
            end
            if(contains(nL,'Pinned') && contains(nR,'Pinned'))
                app.ImageBeamBase_2.Visible='on';
                app.ImageBeamBase_2.ImageSource='beam_bending_base_ss.jpg';              
            end   
            if(contains(nL,'Fixed') && (contains(nR,'Fixed') || contains(nR,'Pinned')))
                app.ImageBeamBase_2.Visible='on';
                app.ImageBeamBase_2.ImageSource='beam_bending_base_fixed.jpg';             
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CloseFigures(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            ChangeCrossSection(app);
            BeamBaseImage(app);
            update_arb(app);
            update_psd(app);
            update_srs(app);
            update_arb_force(app);
            Clearfn(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ChangeMaterials(app);           
        end

        % Callback function
        function CalculateBaseInputSineButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end   
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            app.xx=app.ResponseLocationEditField_sine.Value;  
            
            nu=app.NumberofModesListBox.Value;        
            app.number_modes=round(str2double(nu));
                  
            f_base=app.ExcitationFrequencyHzEditField_sine.Value;
            amp=app.BaseAccelerationGEditField.Value;

            EI=app.E*app.MOI;

            n=app.number_modes;
              
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);      

            %
            arg=app.beta*app.xx;
            %
            sH=0;
            sHv=0;
            sH_relA=0;
            om=2*pi*f_base;
            %
        
            xxu=sort(unique([app.xx app.L*[0 0.5 1]]));
            
            nxx=length(xxu);
               
            bm=zeros(nxx,1);
            shear=zeros(nxx,1);
            %
            for i=1:n
            %
                pY=app.part(i)*ModeShape(arg(i),app.C(i),app.sq_mass);
                omn=2*pi*app.fn(i);
                num=-pY;
                den=(omn^2-om^2)+(1i)*2*app.dm(i)*om*omn;
                nrd=num/den;
                sH=sH+nrd;
            %
                sHv=sHv+(1i)*om*(num/den);
            %
                num=om^2*pY;
                sH_relA=sH_relA+(num/den); 
            %
                for k=1:nxx                      
                       alpha=app.beta(i)*xxu(k);                          
                       pY=app.part(i)*ModeShape_dd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       bm(k)= bm(k) +(num/den);     
                       
                       pY=app.part(i)*ModeShape_ddd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       shear(k)= shear(k) +(num/den);                
                end
            %
            end
            
            bm=abs(bm);
            shear=abs(shear);
            %
            bm=EI*bm;
            shear=EI*shear;
            %
            sH=abs(sH);
            sHv=abs(sHv);
            sHA=abs(sH_relA+1);      
        
            %
            fprintf('  Base excitation = %8.4g G at %8.4g Hz \n',amp,f_base);
            %
            disp(' ');
            if(app.iu==1)
                fprintf('     Response at x=%8.4g inch \n',app.xx);
            else
                fprintf('     Response at x=%8.4g m \n\n',app.xx);    
            end
            %
            fprintf(' Transfer Magnitude = %8.4g G/G \n',sHA);
            fprintf('              Accel = %8.4g G \n',sHA*amp);
            %
        
            if(app.iu==1)
                conv=386;
            else
                conv=9.81;    
            end
        
            sH=sH*conv;
            sHv=sHv*conv;
            
            bm=bm*conv;
            shear=shear*conv;
        
            if(app.iu==1)
                fprintf('            Rel Vel = %8.4g in/sec \n',sHv*amp);
            else
                fprintf('            Rel Vel = %8.4g m/sec \n',sHv*amp);    
            end
            
            if(app.iu==1)
                fprintf('           Rel Disp = %8.4g in \n\n',sH*amp);
            else
                fprintf('           Rel Disp = %8.4g m \n\n',sH*amp);    
            end

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            c=app.cna;
            bm=bm*amp;
            shear=shear*amp;
            %
            if(app.iu==1)
                
                out2=sprintf(' Distance from neutral axis = %8.4g in ',app.cna);
                
                out3=sprintf('\n Bending moment = %8.4g in-lbf at %6.3g in',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g in-lbf at %6.3g in',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g in-lbf at %6.3g in',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g in-lbf at %6.3g in',bm(4),xxu(4));               
                  end        
                
                
                out13=sprintf('\n Shear Force = %8.4g lbf at %6.3g in',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g lbf at %6.3g in',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g lbf at %6.3g in',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g lbf at %6.3g in',shear(4),xxu(4));               
                  end               
                  
                  
                out23=sprintf('\n Bending Stress = %8.4g psi at %6.3g in',(c/app.MOI)*bm(1),xxu(1)); 
                  out24=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(2),xxu(2));   
                  out25=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out26=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(4),xxu(4));               
                  end
            
            else
                
                out2=sprintf('\n Distance from neutral axis = %8.4g mm ',app.cna*1000);
                
                out3=sprintf('\n Bending moment = %8.4g N-m at %6.3g m',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g N-m at %6.3g m',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g N-m at %6.3g m',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g N-m at %6.3g m',bm(4),xxu(4));               
                  end     
                  
                  
                 out13=sprintf('\n Shear Force = %8.4g N at %6.3g m',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g N at %6.3g m',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g N at %6.3g m',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g N at %6.3g m',shear(4),xxu(4));               
                  end           
                   
                
                out23=sprintf('\n Bending Stress = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  out24=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));   
                  out25=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));
            
                   if(length(xxu)==4)
                    out26=sprintf('                = %8.4g Pa at %6.3g m',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    
        %
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            if(length(xxu)==4)
                disp(out6);
            end    
            
        
            disp(out13);
            disp(out14);
            disp(out15);
            if(length(xxu)==4)
                disp(out16);
            end    
        
            disp(out23);
            disp(out24);
            disp(out25);
            if(length(xxu)==4)
                disp(out26);
            end    
            disp(' ');       
                        
            msgbox('Calculation complete.  Output written to Matlab Command Window.');

        end

        % Callback function
        function CalculateBaseInput_HSButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end
            
            A=app.BaseInputAccelGEditField.Value;
            T=app.DurationsecEditField.Value;
            
            disp('  ');
            disp(' * * * * * ');
            disp(' ');           

            fig_num=1;
   
            app.xx=app.ResponseLocationEditField_HS.Value;  

            num_fn=length(app.fn);
            
            nmn=min([str2num(app.NumberofModesListBox_HS.Value) num_fn]);
            ffn=app.fn(1:nmn);        
     
            PF=app.part;

            c=app.cna;    

            EI=app.E*app.MOI;
                    
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);
            %
            dur1=5*T;
            dur2=5/ffn(1);
            dur=max([dur1 dur2]);
            %
            dt1=T/20;
            dt2=(1/max(ffn))/10;
            %
            dt=min([dt1 dt2]);
            %
            num_steps=round(dur/dt);
            %
            t=linspace(0,dur,num_steps);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            omega=pi/T;
            %
            den=zeros(nmn,1);
            U1=zeros(nmn,1);
            U2=zeros(nmn,1);
            V1=zeros(nmn,1);
            V2=zeros(nmn,1);
            P=zeros(nmn,1);
            omegad=zeros(nmn,1);
            domegan=zeros(nmn,1);
            omn2=zeros(nmn,1);
            %
            An=zeros(nmn,1);
            %
            A11=zeros(nmn,1);
            A12=zeros(nmn,1);
            A21=zeros(nmn,1);
            A22=zeros(nmn,1);
            %
            om2=omega^2;
            %    
            for i=1:nmn
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
                domegan(i)=app.dm(i)*app.omegan(i);
                omn2(i)=(app.omegan(i))^2;
                den(i)=( (om2-omn2(i))^2 + (2*app.dm(i)*omega*app.omegan(i))^2);
                U1(i)=2*app.dm(i)*omega*app.omegan(i);
                U2(i)=om2-omn2(i);
                V1(i)=2*app.dm(i)*app.omegan(i)*omegad(i);
                V2(i)=om2-omn2(i)*(1-2*(app.dm(i))^2);
                P(i)=omega/omegad(i);
            %
                A11(i)=-(om2-omn2(i));
                A12(i)=-2*app.dm(i)*omega*app.omegan(i);
                A21(i)=2*app.dm(i)*om2;
                A22(i)=(app.omegan(i)/omegad(i))*(-omn2(i)+om2*(1-2*app.dm(i)^2));
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Transition points
            %
            eee=exp(-domegan*T); 
            %
            arg=omega*T;
            argd=omegad*T;
            %
            c1=cos(arg);
            ss1=sin(arg);
            cd=cos(argd);
            sd=sin(argd);
            %
            W=zeros(nmn,1);
            Tn=zeros(nmn,1);
            Tnv=zeros(nmn,1);
            deee=zeros(nmn,1);
            %
            for j=1:nmn
            %
                An(j)=PF(j)*A;   
            %               
                term1=U1(j)*c1 + U2(j)*ss1;
                term2=V1(j)*cd(j)+V2(j)*sd(j);
                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
            %
                Tn(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                Tnv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
            %
                W(j)= ( Tnv(j) +   app.dm(j)*app.omegan(j)*Tn(j) )/omegad(j);
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                abase=zeros(num_steps,1);
                   rd=zeros(num_steps,1);
                  acc=zeros(num_steps,1);
                   bm=zeros(num_steps,1);
                   shear=zeros(num_steps,1);
            %
            for i=1:num_steps
            %
                    for j=1:nmn
                        eee(j)=exp(-domegan(j)*t(i)); 
                    end   
            %
                    arg=omega*t(i);
                    argd=omegad*t(i);
            %
                    c1=cos(arg);
                    ss1=sin(arg);
                    cd=cos(argd);
                    sd=sin(argd);     
            %
                    n=zeros(nmn,1);
                    nv=zeros(nmn,1);
                    na=zeros(nmn,1);
                    An=zeros(nmn,1);
            %
                    deee=zeros(nmn,1);
            %
                    for j=1:nmn
            %            
                        if(abs(PF(j))>0)
            %
                            if t(i)<=T
            %                   
                                abase(i)=A*ss1;
                                An(j)=PF(j)*A;
            %               
                                term1=U1(j)*c1 + U2(j)*ss1;
                                term2=V1(j)*cd(j)+V2(j)*sd(j);
                                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                                deee(j)=-damp(j)*app.omegan(j)*eee(j);
            %
                                n(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                                nv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);
            %
                                a1=om2*(  A11(j)*ss1  + A12(j)*c1);
                                a2=omega*app.omegan(j)*eee(j)*(  A21(j)*cd(j) +A22(j)*sd(j));
            %                    
                                na(j)=An(j)*(a1+a2)/den(j);
            %
                            else
                                ts=t(i)-T;
            %
                                eee(j)=exp(-domegan(j)*ts); 
                                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);                
            %
                                arg=omega*ts;
            %
                                argd=omegad*ts;
            %
                                c1=cos(arg);
                                ss1=sin(arg);
                                cd=cos(argd);
                                sd=sin(argd);
            %
                                 n(j)=    eee(j)*( Tn(j)*cd(j)  +W(j)*sd(j) );
                                nv(j)=   deee(j)*n(j)...
                                        +omegad(j)*eee(j)*( -Tn(j)*sd(j)  +W(j)*cd(j) );
            %                        
                                na(j)= -2*app.dm(j)*app.omegan(j)*nv(j) -omn2(j)*n(j);      
                            end
            %
                            arg=app.beta(j)*app.xx;
                            app.ZZ=ModeShape(arg,app.C(j),app.sq_mass); 
                            ZZdd=ModeShape_dd(arg,app.C(j),app.beta(j),app.sq_mass);    
                            ZZddd=ModeShape_ddd(arg,app.C(j),app.beta(j),app.sq_mass);                   
            %
                            rd(i)=rd(i) +app.ZZ*n(j);
                           acc(i)=acc(i)+app.ZZ*na(j);
                            bm(i)=bm(i)+ZZdd*n(j);
                            shear(i)=shear(i)+ZZddd*n(j);                
            %                           
                        end  % PF                    
            %
                    end  % j mode
            %
            end      % i  time 
            %     
            acc=acc+abase;
            %
            if(app.iu==1)
                    rd=rd*386;
                    bm=bm*386;
                    shear=shear*386;        
            else
                    rd=rd*9.81;
                    bm=bm*9.81;   
                    shear=shear*9.81;        
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            bm=bm*EI;
            shear=shear*EI;
            %
               
            disp(' ');
            if(app.iu==1)
                fprintf(' Peak Responses at x=%g inch \n\n',app.xx);
            else
                fprintf(' Peak Responses at x=%g m  \n\n',app.xx);    
            end
  
            fprintf('          Acceleration = %8.4g G \n',max(abs(acc)));
            %   
            if(app.iu==1)
                    fprintf(' Relative Displacement = %8.4g in \n\n',max(abs(rd)));   
                    fprintf('        Bending Moment = %8.4g in-lbf \n',max(abs(bm)));  
                    fprintf('           Shear Force = %8.4g lbf \n',max(abs(shear)));        
                    fprintf('        Bending Stress = %8.4g psi \n',(c/app.MOI)*max(abs(bm)));  
            else
                    fprintf(' Relative Displacement = %8.4g m \n\n',max(abs(rd))); 
                    fprintf('        Bending Moment = %8.4g N-m \n',max(abs(bm))); 
                    fprintf('           Shear Force = %8.4g N \n',max(abs(shear)));         
                    fprintf('        Bending Stress = %8.4g Pa \n',(c/app.MOI)*max(abs(bm)));        
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                t=fix_size(t);
            %
                acc=fix_size(acc);
                rd=fix_size(rd);
            %%%
                app.absolute_accel=[t acc];
                app.relative_disp=[t rd];  
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %            
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,rd);
            %
            if(app.iu==1)
                out1=sprintf('Relative Displacement at %g in',app.xx);        
                ylabel('Rel Disp(in) ');
            else
                out1=sprintf('Relative Displacement at %g m',app.xx);        
                ylabel('Rel Disp(m) ');
            end
            title(out1);
            xlabel('Time(sec)');
            grid on;
            %
            %
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,acc,t,abase);
            %
            if(app.iu==1)
                out1=sprintf('Acceleration at %g in',app.xx);
            else
                out1=sprintf('Acceleration at %g m',app.xx);        
            end
            %    
            title(out1);
            ylabel('Accel(G) ');
            xlabel('Time(sec)');
            legend('response','input');  
            grid on;

            setappdata(0,'fig_num',fig_num);    
   
            app.SaveButton_HS.Enable='on';
            
            msgbox('Calculation complete.  Output written to Matlab Command Window.');
                      
        end

        % Callback function
        function CalculateBaseInputPSDButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end     
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            fig_num=1;
            
            app.xx=app.ResponseLocationEditField_PSD.Value;  
            
            nu=app.NumberofModesListBox_PSD.Value;        
            number_modes=round(str2double(nu));
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            num=length(app.THM(:,1));
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end
                   

            ffn=app.fn(1:app.number_modes);
            damp=app.dm(1:app.number_modes);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    acc_trans
            %

            fmin=app.THM(1,1);
            fmax=app.THM(end,1);

            disp(' ');
           
            fstart=0.8*app.THM(1,1);
            fend=app.THM(end,1);

            N=48;
            %
            noct=log(fend/fstart)/log(2);
            np=floor(noct*N);
            %
            freq=zeros(np,1);
            %
            R=2^(1/N);
            %
            freq(1)=fstart;
            for i=2:np
                freq(i)=freq(i-1)*R;
            end
            freq(np)=fend;           
                      
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                  beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,freq,app.part,ffn,damp,app.E,app.MOI);
           
            PSD_in=app.THM;
            
            [app.acc_response_psd]=psd_mult_trans(PSD_in,acc_trans);
            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  );
            
            sz=size(app.acc_response_psd);
            
            fff=app.acc_response_psd(:,1);
            
            %%%%%%%%%%%%%%
        
            app.rv_response_psd=zeros(sz(1),sz(2));
            app.rv_response_psd(:,1)=fff;
            
            app.rd_response_psd=zeros(sz(1),sz(2));
            app.rd_response_psd(:,1)=fff;
            
            app.moment_response_psd=zeros(sz(1),sz(2));
            app.moment_response_psd(:,1)=fff;
            
            app.shear_response_psd=zeros(sz(1),sz(2));
            app.shear_response_psd(:,1)=fff;
            
            app.stress_response_psd=zeros(sz(1),sz(2));
            app.stress_response_psd(:,1)=fff;
        
            %%%%%%%%%%%%%%
            
            disp_rms=0.;
            velox_rms=0;
            bm_rms=0;
            shear_rms=0;
            stress_rms=0.;
            
            vflag=0;
            if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            %    
                vflag=1; 
                [app.rv_response_psd]=psd_mult_trans(PSD_in,rv_trans);
                [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psd(:,1),app.rv_response_psd(:,2)  );
            %
            end   
            
            rflag=0;
            if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
            %    
                rflag=1; 
                [app.rd_response_psd]=psd_mult_trans(PSD_in,rd_trans);
                [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );
            %
            end    
            
            shear_flag=0;
            if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
            %  
                shear_flag=1; 
                [app.shear_response_psd]=psd_mult_trans(PSD_in,shear_trans);
                
                [~,shear_rms]=calculate_PSD_slopes_no(  app.shear_response_psd(:,1),app.shear_response_psd(:,2)  );    
            %
            end
        
            sflag=0;
            if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            %  
                sflag=1; 
                [app.moment_response_psd]=psd_mult_trans(PSD_in,moment_trans);
                
                [~,bm_rms]=calculate_PSD_slopes_no(  app.moment_response_psd(:,1),app.moment_response_psd(:,2)  );    
                       
                app.stress_response_psd(:,1)=        app.moment_response_psd(:,1);    
                app.stress_response_psd(:,2)=(app.cna/app.MOI)^2*app.moment_response_psd(:,2);
                
                [~,stress_rms]=calculate_PSD_slopes_no(  app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 
            %
            end

            if(app.iu==1)
               su='psi';
               sv='in/sec';
               dd='in';
               sb='in-lbf';
               st='psi';
               sh='lbf';
               xu='in';
            else
               su='Pa';
               sv='m/sec';
               dd='mm';
               sb='N-m';
               st='Pa';
               sh='N';
               xu='m';
            %
               disp_rms=disp_rms*1000;
            %
            end

            fprintf('\n Response Overall Levels at x=%g %s \n',app.xx,xu);
            fprintf('\n  Acceleration = %8.4g GRMS \n',accel_rms);
    
            fprintf('  Relative Velocity = %8.4g %s RMS \n',velox_rms,sv);
            fprintf('  Relative displacement = %8.4g %s RMS \n\n',disp_rms,dd);
            fprintf('  Bending moment = %8.4g %s RMS \n',bm_rms,sb);
            fprintf('  Shear Force = %8.4g %s RMS \n',shear_rms,sh);
            fprintf('  Stress = %8.4g %s RMS \n',stress_rms,st);
         
            md=6;
            x_label='Frequency (Hz)';
    
            ylab_acc=sprintf('Accel (G^2/Hz)');
            %
            if(app.iu==1)
                ylab_bm=sprintf('Bending moment ((in-lbf)^2/Hz)');    
                ylab_stress=sprintf('Stress (psi^2/Hz)');
                ylab_rv=sprintf('Rel Vel ((in/sec^2)^2/Hz)');
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
                ylab_sh=sprintf('Shear (lbf^2/Hz)');   
            else
                ylab_bm=sprintf('Bending moment ((N-m)^2/Hz)');     
                ylab_stress=sprintf('Stress (Pa^2/Hz)'); 
                ylab_rv=sprintf('Rel Vel ((m/sec^2)^2/Hz)');    
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
                ylab_sh=sprintf('Shear (N^2/Hz)');      
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes_no( app.THM(:,1),app.THM(:,2) );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            ppp1=app.THM;
            ppp2=app.acc_response_psd;
            
            y_label=ylab_acc;
            %
            if(app.iu==1)
                t_string=sprintf('Acceleration Response PSD  x=%g in',app.xx);
            else
                t_string=sprintf('Acceleration Response PSD  x=%g m',app.xx);    
            end
            %
            leg1=sprintf('Base Input %6.3g GRMS',input_rms);
            leg2=sprintf('Mass Response %6.3g GRMS',accel_rms);
        
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
        
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(vflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Relative Velocity Response PSD  x=%g in, %7.3g %s RMS',app.xx,velox_rms,sv);
                else
                    t_string=sprintf('Relative Velocity Response PSD  x=%g m, %7.3g %s RMS',app.xx,velox_rms,sv); 
                end
            
                y_label=ylab_rv;
            %
                ppp=app.rv_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            %
            end
            
            if(app.iu==2)
                app.rd_response_psd(:,2)=app.rd_response_psd(:,2)*1.0e+06;
            end

            if(rflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Relative displacement PSD  x=%g in, %6.3g %s RMS',app.xx,disp_rms,dd);
                else
                    t_string=sprintf('Relative displacement PSD  x=%g m,  %6.3g %s RMS',app.xx,disp_rms,dd);       
                end
                
                y_label=ylab_rd;
            %
                ppp=app.rd_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(shear_flag==1)
                
                if(app.iu==1)
                    t_string=sprintf('Shear Force PSD  x=%g in,  %7.4g %s RMS',app.xx,shear_rms,sh);
                else
                    t_string=sprintf('Shear Force PSD  x=%g m,  %7.4g %s RMS',app.xx,shear_rms,sh);
                end
                
                y_label=ylab_sh;
            
                ppp=app.shear_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
            end    

            if(sflag==1)
      
                if(app.iu==1)
                    t_string=sprintf('Bending moment PSD  x=%g in,  %7.4g %s RMS',app.xx,bm_rms,sb);
                else
                    t_string=sprintf('Bending moment PSD  x=%g m,  %7.4g %s RMS',app.xx,bm_rms,sb);
                end
                
                y_label=ylab_bm;

                ppp=app.moment_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
                if(app.iu==1)
                    t_string=sprintf('Stress Response PSD  x=%g in,  %7.4g %s RMS',app.xx,stress_rms,su);
                else
                    t_string=sprintf('Stress Response PSD  x=%g m,  %7.4g %s RMS',app.xx,stress_rms,su);
                end
                
                y_label=ylab_stress;

                ppp=app.stress_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            m=1;
            
            if(m==1)
    
                xxL=(0:1:20)*app.L/20;
            
                num=length(xxL);
            
                amax=0;
                vmax=0;
                rdmax=0;
                smax=0;
                bmmax=0;
                shmax=0;
            
                progressbar;
            
                for i=1:num
                
                    progressbar((i-1)/num);
                
                    app.xx=xxL(i);
                
                    [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                      beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,freq,app.part,ffn,damp,app.E,app.MOI);
            
                    if( acc_trans(1,2)>1.0e-12 && max(acc_trans(:,2))>=1.0e-12)		  
            	       try
            	           [app.acc_response_psdm]=psd_mult_trans(PSD_in,acc_trans);
                           [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psdm(:,1),app.acc_response_psdm(:,2)  );
                       catch
            	       end
                    end
                  
                    if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            	       try
            	           [app.rv_response_psdm]=psd_mult_trans(PSD_in,rv_trans);
            	           [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psdm(:,1),app.rv_response_psdm(:,2)  );                       
            	       catch
            	       end
                    end
                    if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
                       try             
                           [app.rd_response_psdm]=psd_mult_trans(PSD_in,rd_trans);
                           [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psdm(:,1),app.rd_response_psdm(:,2)  );           
                       catch
                       end
                    end            
            
                    if(disp_rms>=rdmax)
                        rdmax=disp_rms;
                        xrdmax=app.xx;
                    end       
                    
                    if(velox_rms>=vmax)
                        vmax=velox_rms;
                        xvmax=app.xx;
                    end
                    
                    if(accel_rms>=amax)
                        amax=accel_rms;
                        xamax=app.xx;
                    end
                    
                    if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
                        try
                            [sym]=psd_mult_trans(PSD_in,shear_trans);
                            [~,sh_rms]=calculate_PSD_slopes_no(  sym(:,1),sym(:,2)  );
                        catch 
                            disp('shear fail')
                        end
                    end    
                    
                    if(shear_rms>=shmax)
                        shmax=sh_rms;
                        xshmax=app.xx;
                    end           
                    
                    if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            	       try
              
                            [mym]=psd_mult_trans(PSD_in,moment_trans);			
                
                            [~,bm_rms]=calculate_PSD_slopes_no(  mym(:,1),mym(:,2)  );    
               
                            srm(:,1)=mym(:,1);    
                            srm(:,2)=(app.cna/app.MOI)^2*mym(:,2);
            			
                            [~,stress_rms]=calculate_PSD_slopes_no(  srm(:,1),srm(:,2)  ); 
            
            	       catch
            	       end
                    end
                   
                    if(stress_rms>=smax)
                        smax=stress_rms;
                        xsmax=app.xx;
                    end       
                    if(bm_rms>=bmmax)
                        bmmax=bm_rms;
                        xbmmax=app.xx;
                    end           
            
                end
                
                if(app.iu==2)
                %    disp_rms=disp_rms*1000;
                end
            
                progressbar(1);
            
                disp(' ');
                disp(' Maximum Overall Levels Across Entire Length ');
                disp(' ');
            
                fprintf('  Acceleration Response PSD  x=%g in, %7.3g GRMS \n',xamax,amax);    
                
                if(app.iu==1)
                    t_string=sprintf('  Relative Velocity PSD  x=%g in, %7.3g %s RMS',xvmax,vmax,sv);
                else
                    t_string=sprintf('  Relative Velocity PSD  x=%g m, %7.3g %s RMS',xvmax,vmax,sv); 
                end
                disp(t_string);
                
                if(app.iu==1)
                    t_string=sprintf('  Relative displacement PSD  x=%g in, %7.3g %s RMS',xrdmax,rdmax,dd);
                else
                    t_string=sprintf('  Relative displacement PSD  x=%g m, %7.3g %s RMS',xrdmax,rdmax,dd); 
                end         
                disp(t_string);   
            
                if(app.iu==1)
                    t_string=sprintf('\n  Bending moment PSD  x=%g in,  %7.4g %s RMS',xbmmax,bmmax,sb);
                else
                    t_string=sprintf('  Bending moment PSD  x=%g m,  %7.4g %s RMS',xbmmax,bmmax,sb);
                end                
                disp(t_string);  
                
                if(app.iu==1)
                    t_string=sprintf('  Shear Force PSD  x=%g in,  %7.4g %s RMS',xshmax,shmax,sh);
                else
                    t_string=sprintf('  Shear Force PSD  x=%g m,  %7.4g %s RMS',xshmax,shmax,sh);
                end    
                disp(t_string);      
                
                if(app.iu==1)
                    t_string=sprintf('  Stress Response PSD  x=%g in,  %7.4g %s RMS',xsmax,smax,su);
                else
                    t_string=sprintf('  Stress Response PSD  x=%g m,  %7.4g %s RMS',xsmax,smax,su);
                end    
                disp(t_string);    
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.SaveButton_PSD.Enable='on';
            
            disp(' ');
            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
           
        end

        % Callback function
        function CalculateBaseInputMDOFSRSButtonPushed(app, event)
            
            CloseFigures(app);
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end      

            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end               
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            
            num_srs=length(app.THM(:,1));
            num_fn=length(app.fn);   
            
            fig_num=1;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
            
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % if(app.fn(1)<app.THM(1,1))
            %   warndlg(' First Natural Frequency < First Specification Frequency '); 
            %   return 
            % end  
            % if(app.fn(num_fn)>app.THM(num_srs,1))
            %   warndlg(' Last Natural Frequency < Last Specification Frequency ');
            %   return
            % end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
              
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  
            
            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);
            
             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
                          
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            app.xx=[0. 0.5 1]*app.L;
            nxx=length(app.xx);
            
            num_modes=str2num(app.NumberofModesListBox_MDOFSRS.Value);
            
            if(num_modes>num_fn)
                num_modes=num_fn;
            end
            
            [ModeShape,~,~]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);  
             
            for i=1:nxx
               
                for j=1:num_modes
                    
                    arg=app.beta(j)*app.xx(i);
                    PFM=app.part(j)*ModeShape(arg,app.C(j),app.sq_mass);
                     
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            disp(' ');
            disp(' Pseudo Velocity');
            
            if(app.iu==1)
                out_a='     x(in) SRSS(in/sec)  Abssum(in/sec)';
            else
                out_a='     x(m)  SRSS(cm/sec)  Abssum(cm/sec)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),pv_srss(i),pv_abssum(i));
            end
            
            disp(' ');
            disp(' Relative Displacement');
                
            if(app.iu==1)
                out_a='       x(in)  SRSS(in)  Abssum(in)';
            else
                out_a='       x(m)   SRSS(mm)  Abssum(mm)';   
            end
                
            disp(' ');
            disp(out_a);
            
            for i=1:nxx
                fprintf('     %7.3f  %8.4g  %8.4g \n',app.xx(i),rd_srss(i),rd_abssum(i));
            end
            
            msgbox('Results written to Command Window');
            
        end

        % Callback function
        function CalculateAppliedForceLengthFRFButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end            
           
        end

        % Button pushed function: CalculateNaturalFrequenciesButton
        function CalculateNaturalFrequenciesButtonPushed(app, event)
            
            fprintf('\n * * * * * * * * * \n\n');


            CloseFigures(app);

            if(app.iu==1)
                cn={'Mode','Natural Freq (Hz)','Participation Factor','Modal Mass Ratio','Phase Speed (ft/sec)','Wavenumber (rad/ft)'};
            else
                cn={'Mode','Natural Freq (Hz)','Participation Factor','Modal Mass Ratio','Phase Speed (m/sec)','Wavenumber (rad/m)'};            
            end    

            app.UITable_fn.ColumnName=cn;

            app.L=str2num(app.LengthinEditField.Value);
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);
            
            app.BeamLengthinEditField.Value=app.L;
            app.BeamLengthinEditField_dforce.Value=app.L;

            rho_orig=app.rho;

            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
            end
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=3;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=3;                
            end    
            
            if((app.LBC==2 && app.RBC==3) || (app.LBC==3 && app.RBC==2)) % pinned-free
                warndlg('case unavailable');
                return;
            end 
            
            [root]=beam_bending_roots(app.LBC,app.RBC);
    
            %%%%%%%%%%%%%%%%%%%%%
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=str2num(app.EditField_1.Value);
                thick=str2num(app.EditField_2.Value);
                if(app.iu==2)
                    width=width/1000;
                    thick=thick/1000;            
                end    
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);
                app.cwidth=width;
            end
            if(contains(n,'Pipe'))
                diameter=str2num(app.EditField_1.Value);
                wall_thick=str2num(app.EditField_2.Value);
                if(app.iu==2)
                    diameter=diameter/1000;
                    wall_thick=wall_thick/1000;            
                end             
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);       
                app.cwidth=diameter;
            end
            if(contains(n,'Cylinder'))
                diameter=str2num(app.EditField_1.Value);
                if(app.iu==2)
                    diameter=diameter/1000;            
                end             
                [area,app.MOI,app.cna]=cylinder_geometry(diameter);    
                app.cwidth=diameter;
            end
            if(contains(n,'I-Beam'))
                B=app.str2num(app.EditField_1Label.Value);
                H=app.str2num(app.EditField_2Label.Value);
                Tf=app.str2num(app.EditField_3Label.Value);     
                Tw=app.str2num(app.EditField_4Label.Value);  
                if(app.iu==2)
                    B=B/1000;
                    H=H/1000;
                    Tf=Tf/1000;
                    Tw=Tw/1000;                  
                end                      
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;        
                app.cwidth=max([H B]);
            end
            if(contains(n,'Other'))
                area=app.EditField_1.Value;
                app.MOI=str2num(app.EditField_2.Value);
                app.cna=str2num(app.EditField_3.Value);
                if(app.iu==2)
                    area=area/1000^2;    
                    app.MOI=app.MOI/1000^4;     
                    app.cna=app.cna/1000;   
                    app.cwidth=sqrt(area);
                end                   
            end
            
            app.mass_per_length=app.rho*area;   % mass per unit length
            mass=app.mass_per_length*app.L;
            app.sq_mass=sqrt(mass);
            EI_term = sqrt(app.E*app.MOI/app.mass_per_length);

            mass_orig=rho_orig*app.L*area;

            if(app.iu==1)
                fprintf('\n area=%8.4g in^2    MOI=%8.4g in^4 \n\n',area,app.MOI);
            else
                fprintf('\n area=%8.4g m^2    MOI=%8.4g m^4 \n\n',area,app.MOI);
            end

            %%%%%%%%%%%%%%%%%%%%%
            
            n=6;

            app.fn=zeros(n,1);
            app.beta=zeros(n,1);
            cp=zeros(n,1);
            
            for i=1:n
                app.beta(i)=root(i)/app.L;
                app.omegan=app.beta(i)^2*EI_term;
                app.fn(i)=app.omegan/(2*pi);
                cp(i)=app.beta(i)*EI_term;
            end
                                         
            app.C=zeros(n,1);
            
            app.part=zeros(n,1);
            emm=zeros(n,1);   
           
            if(app.LBC==1 && app.RBC==1) % fixed-fixed
                for i=1:n
                    bL=root(i);
                    app.C(i)=(sinh(bL)+sin(bL))/(cosh(bL)-cos(bL));
                    arg=root(i);
                    p2=(sinh(arg)-sin(arg))-app.C(i)*(cosh(arg)+cos(arg));
                    arg=0;
                    p1=(sinh(arg)-sin(arg))-app.C(i)*(cosh(arg)+cos(arg));
                    app.part(i)=(p2-p1)/app.beta(i);
                end
                ModeShape=@(arg,Co)((cosh(arg)-cos(arg))-Co*(sinh(arg)-sin(arg)));
                app.part=app.part*sqrt(mass/app.L^2);
            end
            %
            if((app.LBC==1 && app.RBC==2) || (app.LBC==2 && app.RBC==1)) % fixed-pinned
        
                for i=1:n
                   app.C(i)=-(sinh(root(i))+sin(root(i)))/(cosh(root(i))+cos(root(i)));
                   arg=root(i);
                   p=(-2+cosh(arg)+cos(arg))+app.C(i)*(sinh(arg)-sin(arg));
                   app.part(i)=p/app.beta(i);
                end      
                ModeShape=@(arg,Co)((sinh(arg)-sin(arg))+Co*(cosh(arg)-cos(arg)));
                app.part=app.part*sqrt(mass/app.L^2);       
            end
            %   
            if((app.LBC==1 && app.RBC==3) || (app.LBC==3 && app.RBC==1)) % fixed-free
        
                for i=1:n
                   app.C(i)=-(cos(root(i))+cosh(root(i)))/(sin(root(i))+sinh(root(i)));
                   arg=root(i);
                   p2=(sinh(arg)-sin(arg))+app.C(i)*(cosh(arg)+cos(arg));
                   arg=0;
                   p1=(sinh(arg)-sin(arg))+app.C(i)*(cosh(arg)+cos(arg));
                   app.part(i)=(p2-p1)/app.beta(i);           
                end
                
                ModeShape=@(arg,Co)((cosh(arg)-cos(arg))+Co*(sinh(arg)-sin(arg)));
                app.part=app.part*sqrt(mass/app.L^2);
            end
            %    
            if(app.LBC==2 && app.RBC==2) % pinned-pinned
                app.C=sqrt(2)*ones(n,1);
                ModeShape=@(arg,Co)(Co*sin(arg));
            %      
                for i=1:n
                   app.part(i)=(-1/(i*pi))*sqrt(2*mass)*(cos(i*pi)-1);
                end
            %       
            end 
            %    
            iflag=0;
            if(app.LBC==3 && app.RBC==3) % free-free
                for i=1:n
                    bL=root(i);
                    app.C(i)=(-cosh(bL)+cos(bL))/(sinh(bL)+sin(bL));
                end
                ModeShape=@(arg,Co)((sinh(arg)+sin(arg))+Co*(cosh(arg)+cos(arg)));
                iflag=1;
            end     
            %%
       
            if(app.iu==1)
                cp=cp/12; 
                ss='(ft/sec)'; 
                st='(rad/ft)';
            else
                ss='(m/sec)'; 
                st='(rad/m)';
            end    
        
            for i=1:n
                if(abs(app.part(i))<1.0e-09)
                    app.part(i)=0;
                end
            end
        
            app.omegan=2*pi*app.fn;
            
            if(app.iu==1)
                ss1=sprintf('\n  Modal Mass unit:  lbf sec^2/in \n\n');
            else
                ss1=sprintf('\n  Modal Mass unit:  kg \n\n');                
            end    

            fprintf(ss1);
        
            if(iflag==0)
             
            %
                ss2=sprintf('        Natural         Participation       Effective       Phase Speed   Wavenumber \n');
                fprintf(ss2);

                ss3=sprintf('Mode \t Freq (Hz) \t Factor \t\t Modal Mass \t  %s \t\t  %s \n',ss,st);
                fprintf(ss3);  

                ss=sprintf('%s %s %s',ss1,ss2,ss3);

            %
                for i=1:n
                    kw=app.omegan(i)/cp(i);
                    emm(i)=app.part(i)^2;
     
                    ss4=sprintf('  %d \t    %10.4g \t     %8.2e \t\t %8.2e \t%8.4g \t\t %8.4g \n',i,app.fn(i),app.part(i),emm(i),cp(i),kw);
                    fprintf(ss4);

                    ss=sprintf('%s %s',ss,ss4);
                end

                
                sm=sum(emm);

                if(app.iu==1)
                    ss5=sprintf(' Total Modal Mass \n   = %8.4g lbf sec^2/in ',sm);
                    ss6=sprintf('\n   = %8.4g lbm \n\n',sm*386);
                else
                    ss5=sprintf(' Total Modal Mass \n   = %8.4g kg ',sm);
                    ss6=sprintf('\n   = %8.4g kg \n\n',sm);
                end    

                fprintf(ss5);
                fprintf(ss6);

                ss=sprintf('%s %s',ss5,ss6);


                if(app.iu==1)
                    ss=sprintf('%s Total Physical Mass=%8.4g lbm',ss,mass_orig);
                else
                    ss=sprintf('%s Total Physical Mass=%8.4g kg',ss,mass_orig);
                end
    
                app.MassResultsTextArea.Value=ss;

                
            else  % free-free

                disp('        Natural    Phase Speed  Wavenumber');
                fprintf('Mode   Freq (Hz)    %s     %s \n',ss,st);

                for i=1:n
                    kw=app.omegan(i)/cp(i);            
                    fprintf('%d  %10.4g      %8.4g    %8.4g',i,app.fn(i),cp(i),kw );
                end

                if(app.iu==1)
                    ss=sprintf(' Total Physical Mass=%8.4g lbm',mass_orig);
                else
                    ss=sprintf(' Total Physical Mass=%8.4g kg',mass_orig);
                end
    
                app.MassResultsTextArea.Value=ss;
                
            end    

      
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                for i=1:n
                    kw=app.omegan(i)/cp(i);
                    emm(i)=app.part(i)^2/mass;
    
                    data_s{i,1}=sprintf('%d',i);
                    data_s{i,2}=sprintf('%8.4g',app.fn(i));
                    data_s{i,3}=sprintf('%7.3g',app.part(i));
                    data_s{i,4}=sprintf('%7.3f',emm(i));
                    data_s{i,5}=sprintf('%7.4g',cp(i));
                    data_s{i,6}=sprintf('%7.3g',kw);
                end

                if(iflag==1)
                    warndlg('Rigid-body mode at 0 Hz omitted from table');
                end    
                
                app.UITable_fn.Data=data_s;
                app.UITable_fn.Visible='on';
                app.MassResultsTextArea.Visible='on';
                app.MassResultsTextAreaLabel.Visible='on';

                s = uistyle('HorizontalAlignment','center'); % create a style
                addStyle(app.UITable_fn,s) % adjust the style


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%  Leave as-is

            if(app.LBC==1 && app.RBC==1) % fixed-fixed    
                ModeShape=@(arg,Co,sq_mass)(((cosh(arg)-cos(arg))-Co*(sinh(arg)-sin(arg)))/sq_mass);    
            end
            if((app.LBC==1 && app.RBC==2) || (app.LBC==2 && app.RBC==1)) % fixed-pinned  
                ModeShape=@(arg,Co,sq_mass)(((sinh(arg)-sin(arg))+Co*(cosh(arg)-cos(arg)))/sq_mass);       
            end
            if((app.LBC==1 && app.RBC==3) || (app.LBC==3 && app.RBC==1)) % fixed-free
                ModeShape=@(arg,Co,sq_mass)(((cosh(arg)-cos(arg))+Co*(sinh(arg)-sin(arg)))/sq_mass);
            end
            if(app.LBC==2 && app.RBC==2) % pinned-pinned
                ModeShape=@(arg,Co,sq_mass)((Co*sin(arg))/sq_mass);     
            end  
            if(app.LBC==3 && app.RBC==3) % free-free
                ModeShape=@(arg,Co,sq_mass)(((sinh(arg)+sin(arg))+Co*(cosh(arg)+cos(arg)))/sq_mass);
            end

 %
            num=500;            
            dx=app.L/num;
            
            app.x=zeros(num+1,1);
            app.y=zeros(num+1,6);
    
            for i=1:num+1
                app.x(i)=(i-1)*dx;
                
                for j=1:n
                    arg=app.beta(j)*app.x(i);
                    app.y(i,j)=ModeShape(arg,app.C(j),app.sq_mass); 
                end
            end

            for j=1:n
                my=max(abs(app.y(:,j)));     
                app.yy(:,j)=app.y(:,j)/my;
            end
 %
            
            app.xx=app.L*[0 0.5 1.0];

            nxx=length(app.xx);
            
            num_fn=length(app.fn);
            
            app.ZZ=zeros(nxx,num_fn);
            %
            
            for j=1:num_fn
            %
                for k=1:nxx      
                    arg=app.beta(j)*app.xx(k);
                    app.ZZ(k,j)=ModeShape(arg,app.C(j),app.sq_mass);
                end
            %        
            end
            
            app.s1=sprintf('%8.4g',app.fn(1));
            app.s2=sprintf('%8.4g',app.fn(2));
            app.s3=sprintf('%8.4g',app.fn(3));
            app.s4=sprintf('%8.4g',app.fn(4));
            app.s5=sprintf('%8.4g',app.fn(5));
            app.s6=sprintf('%8.4g',app.fn(6));
            app.SelectModeforPlotListBox.Items={app.s1;app.s2;app.s3;app.s4;app.s5;app.s6};
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
            ss=sprintf('Select Mode for Plot \n fn (Hz)');
            app.SelectModeforPlotListBoxLabel.Text=ss;
            app.PlotModeShapeButton.Enable='on';
            app.PlotModeShapeButton.Visible='on';

            % SaveDampingButtonPushed(app, event);
            
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            ChangeCrossSection(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=6;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else

                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;           
                    for i=1:length(vd)
                        v=vd{i};
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            app.LBC
            app.RBC

            n=app.SelectModeforPlotListBox.Value;
            
            if(strcmp(n,app.s1))
                i=1;
            end
            if(strcmp(n,app.s2))
                i=2;
            end
            if(strcmp(n,app.s3))
                i=3;
            end
            if(strcmp(n,app.s4))
                i=4;
            end
            if(strcmp(n,app.s5))
                i=5;
            end
            if(strcmp(n,app.s6))
                i=6;
            end            

            figure(1);
            plot(app.x,app.y(:,i));
            grid on;
            xlim([0,app.L]);

            if(app.iu==1)
                xlabel('x (inch) ');
            else
                xlabel('x (m) ');
            end    

            ylabel('Mass Normalized Modal Displacement');
            
            sss=sprintf('Mode %d   fn=%8.4g Hz',i,app.fn(i));
            
            title(sss);
            
            sst=sprintf('mode_%d',i);
            
            assignin('base',sst,[app.x,app.y(:,i)]);

        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            Clearfn(app);
            BeamBaseImage(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            Clearfn(app);
            BeamBaseImage(app);       
        end

        % Callback function
        function LengthinEditFieldValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end

        % Callback function
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFrequencyHzEditField_sine.Value=app.fn(1);
        end

        % Callback function
        function SaveButton_arbitraryPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_arbitrary.Value);
            if(strcmp(n,'Relative displacement'))
                data=app.relative_displacement;
            end
            if(strcmp(n,'Relative Velocity'))
                data=app.relative_velocity;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(strcmp(n,'Bending Stress'))
                data=app.bending_stress;
            end
            output_name=strtrim(app.OutputArrayNameEditField_arbitrary.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');         
        end

        % Callback function
        function SaveButton_PSDPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_PSD.Value);
            if(strcmp(n,'Relative displacement'))
                data=app.rd_response_psd;
            end
            if(strcmp(n,'Relative Velocity'))
                data=app.rv_response_psd;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.acc_response_psd;
            end 
            if(strcmp(n,'Bending moment'))
                data=app.moment_response_psd;
            end
            if(strcmp(n,'Bending Stress'))
                data=app.stress_response_psd;
            end
            if(strcmp(n,'Shear Force'))
                data=app.shear_response_psd;
            end
            output_name=strtrim(app.OutputArrayNameEditField_PSD.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');       
        end

        % Callback function
        function NumberofModesListBox_HSValueChanged(app, event)
            app.SaveButton_HS.Enable='off';
        end

        % Callback function
        function BaseInputAccelGEditFieldValueChanged(app, event)

            app.SaveButton_HS.Enable='off';
        end

        % Callback function
        function DurationsecEditFieldValueChanged(app, event)
            app.SaveButton_HS.Enable='off';
        end

        % Callback function
        function ResponseLocationEditField_HSValueChanged(app, event)
            app.SaveButton_HS.Enable='off';            
        end

        % Callback function
        function SaveButton_HSPushed(app, event)
        output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
        n=strtrim(app.OutputFunctionListBox_HS.Value);
            if(strcmp(n,'Relative Displacement'))
                data=app.relative_disp;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.absolute_accel;
            end 
            
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)

            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Callback function
        function UpdateListButtonPushed(app, event)
            update_arb(app);
        end

        % Callback function
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Callback function
        function listbox_method_srsValueChanged(app, event)
            Items = char(app.listbox_method_srs.Items);
            im=app.listbox_method_srs.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
        end

        % Callback function
        function UpdateListButton_srsPushed(app, event)
            update_srs(app);
        end

        % Value changing function: LengthinEditField
        function LengthinEditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: EditField_1
        function EditField_1ValueChanging(app, event)
            Clearfn(app);  
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            Clearfn(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            Clearfn(app);
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Callback function
        function CalculateBaseInputTrans_2ButtonPushed(app, event)
            
            app.analysis='Trans';

            preprocess(app);

            if(app.kflag==999)
                return;
            end    

            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField_2.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField_2.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
               
            N=48;
            fmin=fstart;
            [f,~,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            ppart=app.part(1:app.number_modes);
               
            
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI);   
                  
            stress_trans=moment_trans;
               
            fprintf('  cna=%8.4g  I=%8.4g  cna/I=%8.4g \n',app.cna,app.MOI,app.cna/app.MOI);
               
            stress_trans(:,2)=stress_trans(:,2)*app.cna/app.MOI;
                
            [maxF,maxH]=find_max_alt(rd_trans);
            [maxFV,maxHV]=find_max_alt(rv_trans);    
            [maxFA,maxHA]=find_max_alt(acc_trans);  
            [maxFBM,maxHBM]=find_max_alt(moment_trans);
            [maxFSH,maxHSH]=find_max_alt(shear_trans);    
            [maxFST,maxHST]=find_max_alt(stress_trans);      
            
            md=6;
            x_label='Frequency (Hz)';
                
            fprintf('\n Response Location: x=%g inches\n\n',app.xx);
       
            fmin=fstart;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g inch',app.xx);
                y_label='Rel Disp (in)/ Base Accel (G)';
            else
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g m',app.xx);
                y_label='Rel Disp (m)/ Base Accel (G)';        
            end
          
            try
                ppp=rd_trans;
                if(min(ppp(:,2))>1.e-90)
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                end    
            catch
            end
           
            disp(' ');
            if(app.iu==1)
                ssr=sprintf('Rel Disp = \n  %7.3g (in/G)  at %7.3g Hz \n\n',maxH,maxF);
            else
                ssr=sprintf('Rel Disp = \n  %7.3g (m/G)  at %7.3g Hz \n\n',maxH,maxF);       
            end
            fprintf(ssr);
            %
            app.relative_disp=rd_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g inch',app.xx);
                    y_label='Rel Vel (in/sec)/ Base Accel (G)';
                else
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g m',app.xx);
                    y_label='Rel Vel (m/sec)/ Base Accel (G)';        
                end
                
                try
                    ppp=rv_trans;
                    if(min(ppp(:,2))>1.e-90)
                        [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    end
                catch
                end
            %
                disp(' ');
                try
                
                    if(app.iu==1)
                        ssv=sprintf('Rel Vel = \n  %7.3g ((in/sec)/G)  at %6.3g Hz \n\n',maxHV,maxFV);
                    else
                        ssv=sprintf('Rel Vel = \n  %7.3g ((m/sec)/G)  at %6.3g Hz \n\n',maxHV,maxFV);       
                    end
                    fprintf(ssv)
                catch 
                end
            %
                app.relative_vel=rv_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g inch',app.xx);
                else
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g m',app.xx);        
                end
                y_label='Absolute Accel / Base Accel';    
                
                ppp=acc_trans; 
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    
                ssa=sprintf('Absolute Accel = \n  %8.4g (G/G)  at %7.3g Hz \n\n',maxHA,maxFA);
                fprintf(ssa);

                ss=sprintf('%s %s %s',ssa,ssv,ssr);

            %
                app.absolute_accel=acc_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(app.iu==1)
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g inch',app.xx);
                    y_label='moment (lbf-in)/ Base Accel (G)';
                else
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g m',app.xx);
                    y_label='moment (N-m)/ Base Accel (G)';        
                end
            %
                ppp=moment_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(app.iu==1)
                    fprintf('  max Bending moment FRF = %9.5g (lbf-in/G)  at %6.3g Hz \n',maxHBM,maxFBM);
                else
                    fprintf('  max Bending moment FRF = %9.5g (N-m/G)  at %6.3g Hz \n',maxHBM,maxFBM);       
                end
            %
                app.bending_moment=moment_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(app.iu==1)
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g inch',app.xx);
                    y_label='Shear (lbf)/ Base Accel (G)';
                else
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g m',app.xx);
                    y_label='Shear (N)/ Base Accel (G)';        
                end
            %
                ppp=shear_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(app.iu==1)
                    fprintf('  max Shear Force FRF = %9.5g (lbf/G) at %8.4g Hz \n',maxHSH,maxFSH);
                else
                    fprintf('  max Shear Force FRF = %9.5g (N/G) at %8.4g Hz \n',maxHSH,maxFSH);       
                end
            %
                app.shear_force=shear_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                ppp=stress_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g inch',app.xx);
                    y_label='Stress (psi) / Base Accel (G)';
                else
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g m',app.xx);
                    y_label='Stress (Pa) / Base Accel (G)';        
                end
            
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
                    
                if(app.iu==1)
                    ss1=sprintf('Bending Stress = \n  %7.4g (psi/G)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g in \n\n %s %s',app.xx,ss,ss1);
                else
                    ss1=sprintf('Bending Stress = \n  %7.4g (Pa/G)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g m \n\n %s %s',app.xx,ss,ss1);
                end

                fprintf(ss1);
       
            %
                app.bending_stress=stress_trans;

            app.ResultsTextArea.Value=ss;

            string_th{1}='Relative Displacement';
            string_th{2}='Relative Velocity';
            string_th{3}='Absolute Acceleration';
            string_th{4}='Bending Moment';
            string_th{5}='Bending Stress';
            string_th{6}='Shear Force';

            app.OutputFunctionListBox_4.Items=string_th;

            OpenSave(app);

        end

        % Value changed function: NumberofModesListBox_base
        function NumberofModesListBox_baseValueChanged(app, event)
            CloseResults(app);
        end

        % Value changing function: ResponseLocationinEditField_base
        function ResponseLocationinEditField_baseValueChanging(app, event)
            CloseResults(app);
        end

        % Button down function: BaseExcitationTab
        function BaseExcitationTabButtonDown(app, event)
  
        end

        % Button pushed function: SaveTransButton_2
        function SaveTransButton_2Pushed(app, event)
            
            Items = char(app.OutputFunctionListBox_4.Items);
            im=app.OutputFunctionListBox_4.Value;
            [n]=find_index(Items,im);

            app.Rainflow.Visible='off';

            if(strcmp(app.analysis,'Trans'))

                if(n==1)
                    data=app.relative_disp;
                end
                if(n==2)
                    data=app.relative_vel;
                end
                if(n==3)
                    data=app.absolute_accel;
                end 
                if(n==4)
                    data=app.bending_moment;
                end
                if(n==5)
                    data=app.bending_stress;
                end
                if(n==6)
                    data=app.shear_force;
                end
            end
            if(strcmp(app.analysis,'Arbitrary'))
                app.Rainflow.Visible='on';
                if(n==1)
                    data=app.relative_displacement;
                end
                if(n==2)
                    data=app.relative_velocity;
                end
                if(n==3)
                    data=app.acceleration;
                end 
                if(n==4)
                    data=app.bending_stress;
                end
            end
            if(strcmp(app.analysis,'Half-Sine'))
                if(contains(im,'Disp'))
                    data=app.relative_displacement;
                end
                if(contains(im,'Accel'))
                    data=app.acceleration;
                end 
                if(contains(im,'Stress'))
                    data=app.bending_stress;
                end
            end       
            if(strcmp(app.analysis,'PSD'))
                if(contains(im,'Disp'))
                    data=app.relative_displacement;
                end
                if(contains(im,'Accel'))
                    data=app.acceleration;
                end 
                if(contains(im,'Stress'))
                    data=app.bending_stress;
                end
            end                 

            output_name=strtrim(app.OutputArrayNameEditField_4.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');   

        end

        % Button pushed function: CalculateBaseInputArbitrary_2
        function CalculateBaseInputArbitrary_2ButtonPushed(app, event)
               
            app.analysis='Arbitrary';

            preprocess(app);

            if(app.kflag==999)
                return;
            end    

            fig_num=1;

            Items = char(app.listbox_method_2.Items);
            im=app.listbox_method_2.Value;
            [n]=find_index(Items,im);
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_3.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end                             
        
            %
            t=app.THM(:,1);
            num_steps=length(t);
            abase=app.THM(:,2);
            %
            dt=mean(diff(t));
            %            
        
            disp(' ')
            disp(' Time Step ');
            dtmin=min(diff(t));
            dtmax=max(diff(t));
            %
            fprintf(' dtmin  = %8.4g sec  \n',dtmin);
            fprintf(' dt     = %8.4g sec  \n',dt);
            fprintf(' dtmax  = %8.4g sec  \n',dtmax);
            %
            disp(' ')
            disp(' Sample Rate ');
            fprintf(' srmin  = %8.4g samples/sec  \n',1/dtmax);
            fprintf(' sr     = %8.4g samples/sec  \n',1/dt);
            fprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
  
            %
            if(((dtmax-dtmin)/dt)>0.01)
                disp(' ')
                warndlg(' Warning:  time step is not constant.');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
 
            EI=app.E*app.MOI;
            
            PF=app.part;

            n=app.number_modes;
              
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);         

            disp(' ');
            
            disp('        Natural    Participation   Damping  ');
            disp('Mode   Frequency      Factor        Ratio   ');
            %
            for i=1:length(app.fn)
                fprintf('%d  %10.4g Hz   %10.4g   %8.4g \n',i,app.fn(i),app.part(i),app.dm(i) );
            end    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            nrd=zeros(num_steps,n);
            nrv=zeros(num_steps,n);
            nra=zeros(num_steps,n); 
            %     
            [a1,a2,ra_b1,ra_b2,ra_b3,rv_b1,rv_b2,rv_b3,rd_b1,rd_b2,rd_b3]=...
                                              srs_coefficients_avd(app.fn,app.dm,dt);
            %

            xxu=[ app.L*[0 0.5 1] app.xx ];
            
            nxx=length(xxu);
            
            rd=zeros(num_steps,nxx);
            rv=zeros(num_steps,nxx);
            ra=zeros(num_steps,nxx);    
            bm=zeros(num_steps,nxx);
            shear=zeros(num_steps,nxx);
            %
            app.ZZ=zeros(nxx,app.number_modes);  
            
            for j=1:app.number_modes
            %
                if(abs(PF(j))>0)
            %
                    yp=-abase*PF(j);
            %
                    back   =[     1, -a1(j), -a2(j) ];
            %
            %   relative velocity
            %
                    clear forward;
                    forward=[ rv_b1(j),  rv_b2(j),  rv_b3(j) ];    
                    nrv(:,j)=filter(forward,back,yp);
            %
            %   relative displacement
            %
                    clear forward;
                    forward=[ rd_b1(j),  rd_b2(j),  rd_b3(j) ];      
                    nrd(:,j)=filter(forward,back,yp);
            %
            %   relative acceleraiton
            %
                    clear forward;  
                    forward=[ ra_b1(j),  ra_b2(j),  ra_b3(j) ];    
                    nra(:,j)=filter(forward,back,yp);                
            %            
                    for k=1:nxx      
                     
                       arg=app.beta(j)*xxu(k);
                       app.ZZ(k,j)=ModeShape(arg,app.C(j),app.sq_mass);
            %
                       rd(:,k)= rd(:,k) +app.ZZ(k,j)*nrd(:,j);
                       rv(:,k)= rv(:,k) +app.ZZ(k,j)*nrv(:,j);
                       ra(:,k)= ra(:,k) +app.ZZ(k,j)*nra(:,j);                
                                    
                       ZZdd=ModeShape_dd(arg,app.C(j),app.beta(j),app.sq_mass);
                       ZZddd=ModeShape_ddd(arg,app.C(j),app.beta(j),app.sq_mass);
                          bm(:,k)=    bm(:,k) +ZZdd*nrd(:,j);  
                       shear(:,k)= shear(:,k) +ZZddd*nrd(:,j);               
                    end
            %        
                end
            end
            %
            bm=bm*EI;
            shear=shear*EI;
            %

            nmn=length(abase);
            acc=zeros(nmn,nxx);
            %
            for i=1:nxx
                acc(:,i)=ra(:,i)+abase;
            end

            if(app.iu==1)
                rd=rd*386;
                rv=rv*386;        
                bm=bm*386; 
                shear=shear*386;
            else
                rd=rd*9.81;
                rv=rv*9.81;       
                bm=bm*9.81;     
                shear=shear*9.81;
            end

            if(app.iu==1)
                  fprintf('\n Absolute Acceleration = %8.4g G at %g in \n',max(abs(acc(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g G at %g in \n',max(abs(acc(:,2))),xxu(2));  
                  fprintf('                       = %8.4g G at %g in \n',max(abs(acc(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                     fprintf('                       = %8.4g G at %g in \n',max(abs(acc(:,4))),xxu(4));               
                  end          
            else
                  fprintf('\n Absolute  Acceleration = %8.4g G at %g m \n',max(abs(acc(:,1))),xxu(1)); 
                  fprintf('                        = %8.4g G at %g m \n',max(abs(acc(:,2))),xxu(2));  
                  fprintf('                        = %8.4g G at %g m \n',max(abs(acc(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                       = %8.4g G at %g m \n',max(abs(rv(:,4))),xxu(4));               
                  end
            end    
            
            if(app.iu==1)
                  fprintf('\n Relative Velocity = %8.4g in/sec at %g in \n',max(abs(rv(:,1))),xxu(1)); 
                  fprintf('                   = %8.4g in/sec at %g in \n',max(abs(rv(:,2))),xxu(2));  
                  fprintf('                   = %8.4g in/sec at %g in \n',max(abs(rv(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                  = %8.4g in/sec at %g in \n',max(abs(rv(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Relative Velocity = %8.4g m/sec at %g m \n',max(abs(rv(:,1))),xxu(1)); 
                  fprintf('                   = %8.4g m/sec at %g m \n',max(abs(rv(:,2))),xxu(2));  
                  fprintf('                   = %8.4g m/sec at %g m \n',max(abs(rv(:,3))),xxu(3));           
           
                  if(length(xxu)==4)
                    fprintf('                  = %8.4g m/sec at %g m \n',max(abs(rv(:,4))),xxu(4));               
                  end
            end    

            if(app.iu==1)
                  fprintf('\n Relative displacement = %8.4g in at %g in \n',max(abs(rd(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g in at %g in \n',max(abs(rd(:,2))),xxu(2));  
                  fprintf('                       = %8.4g in at %g in \n',max(abs(rd(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                      = %8.4g in at %g in \n',max(abs(rd(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Relative displacement = %8.4g mm at %g m \n',max(abs(rd(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g mm at %g m \n',max(abs(rd(:,2))),xxu(2));  
                  fprintf('                       = %8.4g mm at %g m \n',max(abs(rd(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                      = %8.4g mm at %g m \n',max(abs(rd(:,4))),xxu(4));               
                  end
            end    

            if(app.iu==1)
                  fprintf('\n Bending moment = %8.4g in-lbf at %g in \n',max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Bending moment = %8.4g Nm at %g m \n',max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g Nm at %g m \n',max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g Nm at %g m \n',max(abs(bm(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g in-lbf at %g m \n',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    

            if(app.iu==1)
                  fprintf('\n  Shear Force = %8.4g lbf at %g in \n',max(abs(shear(:,1))),xxu(1)); 
                  fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,2))),xxu(2));  
                  fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                     fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Shear Force = %8.4g N at %g m \n',max(abs(shear(:,1))),xxu(1)); 
                  fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,2))),xxu(2));  
                  fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,4))),xxu(4));               
                  end   
            end    

            c=app.cna;

            if(app.iu==1)
                
                  fprintf('\n Distance from neutral axis = %8.4g in \n',app.cna);
                
                  fprintf('\n Bending Stress = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,4))),xxu(4));               
                  end
            
            else
                
                  fprintf('\n Distance from neutral axis = %8.4g m \n',app.cna);
                
                  fprintf('\n Bending Stress = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g Pa at %g m \n',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    

            t=fix_size(t);
            acc=fix_size(acc);
            rd=fix_size(rd);

            for i=1:nxx
                if(app.xx==xxu(i))
                    k=i;
                    break;
                end
            end

            bstress=(c/app.MOI)*bm(:,k);
            bstress=fix_size(bstress);

            figure(fig_num)
            fig_num=fig_num+1;

            plot(t,abase);

            title('Base Input Acceleration');
            ylabel('Accel(G) ');
            xlabel('Time(sec)'); 
            grid on;

            figure(fig_num)
            fig_num=fig_num+1;

            plot(t,acc(:,k)); 
            app.acceleration=[t acc(:,k)];

            if(app.iu==1)
                out1=sprintf('Response Absolute Acceleration at %g in',app.xx);            
            else
                out1=sprintf('Response Absolute Acceleration at %g m',app.xx);               
            end
            sa=sprintf('Absolute Acceleration = %8.4g G \n',max(abs(acc(:,k)))); 
            sao=sprintf('Absolute Acceleration = %8.4g G rms \n',std(acc(:,k))); 

            title(out1);
            ylabel('Accel(G) ');
            xlabel('Time(sec)'); 
            grid on;

            figure(fig_num)
            fig_num=fig_num+1;

            if(app.iu==1)
                plot(t,rv(:,k));
                out1=sprintf('Relative Velocity at %g in',app.xx);
                ylabel('Rel Vel (in/sec)');      
                sv=sprintf('Relative Velocity = %8.4g in/sec \n',max(abs(rv(:,k))));   
                svo=sprintf('Relative Velocity = %8.4g in/sec rms\n',std(rv(:,k)));                 
            else
                plot(t,rv(:,k));
                out1=sprintf('Relative Velocity  at %g m',app.xx);
                ylabel('Rel Vel (m/sec)'); 
                sv=sprintf('Relative Velocity = %8.4g m/sec \n',max(abs(rv(:,k))));                     
                svo=sprintf('Relative Velocity = %8.4g m/sec rms\n',std(rv(:,k)));  
            end
            app.relative_velocity=[t rv(:,k)];   
            title(out1);
            xlabel('Time(sec)'); 
            grid on;     

            figure(fig_num)
            fig_num=fig_num+1;

            if(app.iu==1)
                plot(t,rd(:,k));
                out1=sprintf('Relative displacement at %g in',app.xx);
                ylabel('Rel Disp (inch)');      
                sr=sprintf('Relative Disp = %7.3g in \n',max(abs(rd(:,k))));     
                sro=sprintf('Relative Disp = %7.3g in rms \n',std(rd(:,k)));               
            else
                plot(t,rd(:,k)*1000);
                out1=sprintf('Relative displacement  at %g m',app.xx);
                ylabel('Rel Disp (mm)');     
                sr=sprintf('Relative Disp = %7.3g m \n',max(abs(rd(:,k)))); 
                sro=sprintf('Relative Disp = %7.3g m rms \n',std(rd(:,k)));            
            end
            app.relative_displacement=[t rd(:,k)];       
            title(out1);
            xlabel('Time(sec)'); 
            grid on;    

            figure(fig_num)

            stss=std(bstress);
        
            if(app.iu==1)
                plot(t,bstress);
                out1=sprintf('Bending Stress at %g in, %8.4g psi RMS',app.xx,stss);
                ylabel('Stress (psi)');      
                ss=sprintf('Bending Stress = %8.4g psi \n\n',max(abs(bstress)));  
                sso=sprintf('Bending Stress = %8.4g psi rms\n\n',std(bstress));                 
            else
                plot(t,bstress);
                out1=sprintf('Bending Stress  at %g m, %8.4g Pa RMS',app.xx,stss);
                ylabel('Stress (Pa)');   
                ss=sprintf('Bending Stress = %8.4g Pa \n\n',max(abs(bstress))); 
                sso=sprintf('Bending Stress = %8.4g Pa rms\n\n',std(bstress));                 
            end
            app.bending_stress=[t bstress];   
            title(out1);
            xlabel('Time(sec)'); 
            grid on;        

            ss1=sprintf('Peak Response at %g inch \n\n %s %s %s %s',app.xx,sa,sv,sr,ss);
            app.ResultsTextArea.Value=sprintf(' %s Overall Response at %g inch \n\n %s %s %s %s',ss1,app.xx,sao,svo,sro,sso);

            string_th{1}='Relative Displacement';
            string_th{2}='Relative Velocity';
            string_th{3}='Absolute Acceleration';
            string_th{4}='Bending Stress';

            app.OutputFunctionListBox_4.Items=string_th;

            OpenSave(app);
        
        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed(app, event)
            update_arb(app);
        end

        % Value changed function: listbox_method_2
        function listbox_method_2ValueChanged(app, event)

            Items = char(app.listbox_method_2.Items);
            im=app.listbox_method_2.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_arb(app);
               app.SelectInputArrayListBox_3.Value=name;
            end    


        end

        % Button pushed function: SettoFirstfnButton_2
        function SettoFirstfnButton_2Pushed(app, event)
           app.ExcitationFrequencyHzEditField.Value=sprintf('%8.4g',app.fn(1));
           CloseResults(app);
        end

        % Button pushed function: CalculateBaseInputSine_2
        function CalculateBaseInputSine_2ButtonPushed(app, event)
            
            preprocess(app);

            if(app.kflag==999)
                return;
            end    
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            

            f_base=str2num(app.ExcitationFrequencyHzEditField.Value);
            amp=str2num(app.BaseAccelGEditField.Value);

            EI=app.E*app.MOI;

            n=app.number_modes;
              
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);      

            %
            arg=app.beta*app.xx;
            %
            sH=0;
            sHv=0;
            sH_relA=0;
            om=2*pi*f_base;
            %
        
            xxu=[app.L*[0 0.5 1] app.xx];
            
            nxx=length(xxu);
               
            bm=zeros(nxx,1);
            shear=zeros(nxx,1);
            %
            for i=1:n
            %
                pY=app.part(i)*ModeShape(arg(i),app.C(i),app.sq_mass);
                omn=2*pi*app.fn(i);
                num=-pY;
                den=(omn^2-om^2)+(1i)*2*app.dm(i)*om*omn;
                nrd=num/den;
                sH=sH+nrd;
            %
                sHv=sHv+(1i)*om*(num/den);
            %
                num=om^2*pY;
                sH_relA=sH_relA+(num/den); 
            %
                for k=1:nxx                      
                       alpha=app.beta(i)*xxu(k);                          
                       pY=app.part(i)*ModeShape_dd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       bm(k)= bm(k) +(num/den);     
                       
                       pY=app.part(i)*ModeShape_ddd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       shear(k)= shear(k) +(num/den);                
                end
            %
            end
            
            bm=abs(bm);
            shear=abs(shear);
            %
            bm=EI*bm;
            shear=EI*shear;
            %
            sH=abs(sH);
            sHv=abs(sHv);
            sHA=abs(sH_relA+1);      
        
            %
            fprintf('  Base excitation = %8.4g G at %8.4g Hz \n',amp,f_base);
            %
            disp(' ');
            if(app.iu==1)
                fprintf('     Response at x=%8.4g inch \n',app.xx);
            else
                fprintf('     Response at x=%8.4g m \n\n',app.xx);    
            end
            %
            fprintf(' Transfer Magnitude = %8.4g G/G \n',sHA);
            fprintf('              Accel = %8.4g G \n',sHA*amp);
            sa=sprintf('Acceleration = %8.4g G \n\n',sHA*amp);
            %
        
            if(app.iu==1)
                conv=386;
            else
                conv=9.81;    
            end
        
            sH=sH*conv;
            sHv=sHv*conv;
            
            bm=bm*conv;
            shear=shear*conv;
        
            if(app.iu==1)
                fprintf('            Rel Vel = %8.4g in/sec \n',sHv*amp);
                sv=sprintf('Rel Velocity = %7.3g in/sec \n\n',sHv*amp);
            else
                fprintf('            Rel Vel = %8.4g m/sec \n',sHv*amp);  
                sv=sprintf('Rel Velocity = %7.3g in/sec \n\n',sHv*amp);             
            end
            
            if(app.iu==1)
                fprintf('           Rel Disp = %8.4g in \n\n',sH*amp);
                sr=sprintf('Rel Displacement = %7.3g in \n\n',sH*amp);  
            else
                fprintf('           Rel Disp = %8.4g m \n\n',sH*amp); 
                sr=sprintf('Rel Displacement = %7.3g m \n\n',sH*amp);                
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            c=app.cna;
            bm=bm*amp;
            shear=shear*amp;
            bs=(c/app.MOI)*bm(4);
            %
            if(app.iu==1)
                
                out2=sprintf(' Distance from neutral axis = %8.4g in ',app.cna);
                
                out3=sprintf('\n Bending moment = %8.4g in-lbf at %6.3g in',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g in-lbf at %6.3g in',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g in-lbf at %6.3g in',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g in-lbf at %6.3g in',bm(4),xxu(4));               
                  end        
                
                
                out13=sprintf('\n Shear Force = %8.4g lbf at %6.3g in',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g lbf at %6.3g in',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g lbf at %6.3g in',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g lbf at %6.3g in',shear(4),xxu(4));               
                  end               
                  
                  
                out23=sprintf('\n Bending Stress = %8.4g psi at %6.3g in',(c/app.MOI)*bm(1),xxu(1)); 
                  out24=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(2),xxu(2));   
                  out25=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out26=sprintf('                = %8.4g psi at %6.3g in',bs,xxu(4));
                    ss=sprintf('Bending Stress = %7.3g psi \n\n',bs);  
                  end
            
            else
                
                out2=sprintf('\n Distance from neutral axis = %8.4g mm ',app.cna*1000);
                
                out3=sprintf('\n Bending moment = %8.4g N-m at %6.3g m',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g N-m at %6.3g m',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g N-m at %6.3g m',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g N-m at %6.3g m',bm(4),xxu(4));               
                  end     
                  
                  
                 out13=sprintf('\n Shear Force = %8.4g N at %6.3g m',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g N at %6.3g m',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g N at %6.3g m',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g N at %6.3g m',shear(4),xxu(4));               
                  end           
                   

                  size(bm)
                  size(xxu)

                
                out23=sprintf('\n Bending Stress = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(1))),xxu(1)); 
                  out24=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(2))),xxu(2));   
                  out25=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(3))),xxu(3));
            
                   if(length(xxu)==4)
                    out26=sprintf('                = %8.4g Pa at %6.3g m',max(abs(bm(4))),xxu(4));
                    ss=sprintf('Bending Stress = %7.3g Pa \n\n',bs);   
                  end   
            end    
        %
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            if(length(xxu)==4)
                disp(out6);
            end    
                   
            disp(out13);
            disp(out14);
            disp(out15);
            if(length(xxu)==4)
                disp(out16);
            end    
        
            disp(out23);
            disp(out24);
            disp(out25);
            if(length(xxu)==4)
                disp(out26);
            end    
            disp(' ');

            app.ResultsTextArea.Value=sprintf('Response at %7.3g in \n\n %s %s %s %s',app.xx,sa,sv,sr,ss);
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
 
        end

        % Value changing function: ExcitationFrequencyHzEditField
        function ExcitationFrequencyHzEditFieldValueChanging(app, event)
            CloseResults(app);
        end

        % Value changing function: BaseAccelGEditField
        function BaseAccelGEditFieldValueChanging(app, event)
            CloseResults(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                        
            app.analysis='Half-Sine';

            preprocess(app);

            if(app.kflag==999)
                return;
            end   

            A=str2num(app.BaseInputAccelGEditField.Value);
            T=str2num(app.DurationsecEditField.Value);

            disp('  ');
            disp(' * * * * * ');
            disp(' ');           

            fig_num=1; 

            num_fn=length(app.fn);
            
            nmn=min([app.number_modes num_fn]);
            ffn=app.fn(1:nmn);        
     
            PF=app.part;

            c=app.cna;    

            EI=app.E*app.MOI;
                    
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);
            %
            dur1=6*T;
            dur2=5/ffn(1);
            dur=max([dur1 dur2]);
            %
            dt1=T/24;
            dt2=(1/max(ffn))/12;
            %
            dt=min([dt1 dt2]);
            %
            num_steps=round(dur/dt);
            %
            t=linspace(0,dur,num_steps);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            omega=pi/T;
            %
            den=zeros(nmn,1);
            U1=zeros(nmn,1);
            U2=zeros(nmn,1);
            V1=zeros(nmn,1);
            V2=zeros(nmn,1);
            P=zeros(nmn,1);
            omegad=zeros(nmn,1);
            domegan=zeros(nmn,1);
            omn2=zeros(nmn,1);
            %
            An=zeros(nmn,1);
            %
            A11=zeros(nmn,1);
            A12=zeros(nmn,1);
            A21=zeros(nmn,1);
            A22=zeros(nmn,1);
            %
            om2=omega^2;
            %    
            for i=1:nmn
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
                domegan(i)=app.dm(i)*app.omegan(i);
                omn2(i)=(app.omegan(i))^2;
                den(i)=( (om2-omn2(i))^2 + (2*app.dm(i)*omega*app.omegan(i))^2);
                U1(i)=2*app.dm(i)*omega*app.omegan(i);
                U2(i)=om2-omn2(i);
                V1(i)=2*app.dm(i)*app.omegan(i)*omegad(i);
                V2(i)=om2-omn2(i)*(1-2*(app.dm(i))^2);
                P(i)=omega/omegad(i);
            %
                A11(i)=-(om2-omn2(i));
                A12(i)=-2*app.dm(i)*omega*app.omegan(i);
                A21(i)=2*app.dm(i)*om2;
                A22(i)=(app.omegan(i)/omegad(i))*(-omn2(i)+om2*(1-2*app.dm(i)^2));
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Transition points
            %
            eee=exp(-domegan*T); 
            %
            arg=omega*T;
            argd=omegad*T;
            %
            c1=cos(arg);
            ss1=sin(arg);
            cd=cos(argd);
            sd=sin(argd);
            %
            W=zeros(nmn,1);
            Tn=zeros(nmn,1);
            Tnv=zeros(nmn,1);
            deee=zeros(nmn,1);
            %
            for j=1:nmn
            %
                An(j)=PF(j)*A;   
            %               
                term1=U1(j)*c1 + U2(j)*ss1;
                term2=V1(j)*cd(j)+V2(j)*sd(j);
                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
            %
                Tn(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                Tnv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
            %
                W(j)= ( Tnv(j) +   app.dm(j)*app.omegan(j)*Tn(j) )/omegad(j);
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                abase=zeros(num_steps,1);
                   rd=zeros(num_steps,1);
                  acc=zeros(num_steps,1);
                   bm=zeros(num_steps,1);
                   shear=zeros(num_steps,1);
            %
            for i=1:num_steps
            %
                    for j=1:nmn
                        eee(j)=exp(-domegan(j)*t(i)); 
                    end   
            %
                    arg=omega*t(i);
                    argd=omegad*t(i);
            %
                    c1=cos(arg);
                    ss1=sin(arg);
                    cd=cos(argd);
                    sd=sin(argd);     
            %
                    n=zeros(nmn,1);
                    nv=zeros(nmn,1);
                    na=zeros(nmn,1);
                    An=zeros(nmn,1);
            %
                    deee=zeros(nmn,1);
            %
                    for j=1:nmn
            %            
                        if(abs(PF(j))>0)
            %
                            if t(i)<=T
            %                   
                                abase(i)=A*ss1;
                                An(j)=PF(j)*A;
            %               
                                term1=U1(j)*c1 + U2(j)*ss1;
                                term2=V1(j)*cd(j)+V2(j)*sd(j);
                                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
            %
                                n(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                                nv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);
            %
                                a1=om2*(  A11(j)*ss1  + A12(j)*c1);
                                a2=omega*app.omegan(j)*eee(j)*(  A21(j)*cd(j) +A22(j)*sd(j));
            %                    
                                na(j)=An(j)*(a1+a2)/den(j);
            %
                            else
                                ts=t(i)-T;
            %
                                eee(j)=exp(-domegan(j)*ts); 
                                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);                
            %
                                arg=omega*ts;
            %
                                argd=omegad*ts;
            %
                                c1=cos(arg);
                                ss1=sin(arg);
                                cd=cos(argd);
                                sd=sin(argd);
            %
                                 n(j)=    eee(j)*( Tn(j)*cd(j)  +W(j)*sd(j) );
                                nv(j)=   deee(j)*n(j)...
                                        +omegad(j)*eee(j)*( -Tn(j)*sd(j)  +W(j)*cd(j) );
            %                        
                                na(j)= -2*app.dm(j)*app.omegan(j)*nv(j) -omn2(j)*n(j);      
                            end
            %
                            arg=app.beta(j)*app.xx;
                            app.ZZ=ModeShape(arg,app.C(j),app.sq_mass); 
                            ZZdd=ModeShape_dd(arg,app.C(j),app.beta(j),app.sq_mass);    
                            ZZddd=ModeShape_ddd(arg,app.C(j),app.beta(j),app.sq_mass);                   
            %
                            rd(i)=rd(i) +app.ZZ*n(j);
                           acc(i)=acc(i)+app.ZZ*na(j);
                            bm(i)=bm(i)+ZZdd*n(j);
                            shear(i)=shear(i)+ZZddd*n(j);                
            %                           
                        end  % PF                    
            %
                    end  % j mode
            %
            end      % i  time 
            %     
            acc=acc+abase;
            %
            if(app.iu==1)
                    rd=rd*386;
                    bm=bm*386;
                    shear=shear*386;        
            else
                    rd=rd*9.81;
                    bm=bm*9.81;   
                    shear=shear*9.81;        
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            bm=bm*EI;
            shear=shear*EI;
            %
               
            disp(' ');
            if(app.iu==1)
                fprintf(' Peak Responses at x=%g inch \n\n',app.xx);
            else
                fprintf(' Peak Responses at x=%g m  \n\n',app.xx);    
            end
  
            fprintf('          Acceleration = %8.4g G \n',max(abs(acc)));
            %   

            bm=(c/app.MOI)*bm;
            bstress=max(abs(bm));

            [pv]=differentiate_function(rd,dt);

            if(app.iu==1)
                    fprintf(' Relative Displacement = %8.4g in \n\n',max(abs(rd)));   
                    % fprintf('       Pseudo Velocity = %8.4g in/sec \n\n',max(abs(pv)));
                    fprintf('        Bending Moment = %8.4g in-lbf \n',max(abs(bm)));  
                    fprintf('           Shear Force = %8.4g lbf \n',max(abs(shear)));        
                    fprintf('        Bending Stress = %8.4g psi \n',bstress);  
                    sv=sprintf('Pseudo Velocity = %8.4g in/sec \n\n',max(abs(pv)));
                    sr=sprintf('Rel Displacement = %7.3g in \n\n',max(abs(rd))); 
                    ss=sprintf('Bending Stress = %7.3g psi \n\n',bstress);   
            else
                    fprintf(' Relative Displacement = %8.4g m \n\n',max(abs(rd))); 
                    % fprintf('       Pseudo Velocity = %8.4g m/sec \n\n',max(abs(pv)));
                    fprintf('        Bending Moment = %8.4g N-m \n',max(abs(bm))); 
                    fprintf('           Shear Force = %8.4g N \n',max(abs(shear)));         
                    fprintf('        Bending Stress = %8.4g Pa \n',bstress);  
                    sv=sprintf('Pseudo Velocity = %8.4g m/sec \n\n',max(abs(pv)));
                    sr=sprintf('Rel Displacement = %7.3g m \n\n',max(abs(rd))); 
                    ss=sprintf('Bending Stress = %7.3g psi \n\n',bstress);                   
            end

            sa=sprintf('Acceleration = %8.4g G \n\n',max(abs(acc)));

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                t=fix_size(t);
            %
                acc=fix_size(acc);
                rd=fix_size(rd);
            %%%
                app.acceleration=[t acc];
                app.relative_displacement=[t rd];  
                app.bending_stress=[t bm];

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %            
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,rd);
            %
            if(app.iu==1)
                out1=sprintf('Relative Displacement at %g in',app.xx);        
                ylabel('Rel Disp(in) ');
            else
                out1=sprintf('Relative Displacement at %g m',app.xx);        
                ylabel('Rel Disp(m) ');
            end
            title(out1);
            xlabel('Time(sec)');
            grid on;
            %
            %
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,acc,t,abase);
            %
            if(app.iu==1)
                out1=sprintf('Acceleration at %g in',app.xx);
            else
                out1=sprintf('Acceleration at %g m',app.xx);        
            end
            %    
            title(out1);
            ylabel('Accel(G) ');
            xlabel('Time(sec)');
            legend('response','input');  
            grid on;
            %            
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,bm);
            %
            if(app.iu==1)
                out1=sprintf('Bending Stress at %g in',app.xx);
            else
                out1=sprintf('Bending Stress at %g m',app.xx);        
            end
            %    
            title(out1);
            if(app.iu==1)            
                ylabel('Stress (psi) ');
            else
                ylabel('Stress (Pa) ');
            end    

            xlabel('Time (sec)');  
            grid on;

            if(app.iu==1)
                ss1=sprintf('Peak Response at %g inch \n\n %s %s %s',app.xx,sa,sr,ss);
            else
                ss1=sprintf('Peak Response at %g m \n\n %s %s %s ',app.xx,sa,sr,ss);                
            end
           
            app.ResultsTextArea.Value=ss1;

            string_th{1}='Relative Displacement';
            string_th{2}='Absolute Acceleration';
            string_th{3}='Bending Stress';

            app.OutputFunctionListBox_4.Items=string_th;
            OpenSave(app);

        end

        % Button pushed function: UpdateListButton_psd_3
        function UpdateListButton_psd_3Pushed(app, event)
            app.analysis='PSD';

            preprocess(app);

            if(app.kflag==999)
                return;
            end   

            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            fig_num=1;
            
            Items = char(app.listbox_method_psd_2.Items);
            im=app.listbox_method_2.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox_psd_2.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end    

            ffn=app.fn(1:app.number_modes);
            damp=app.dm(1:app.number_modes);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    acc_trans
            %

            fmin=app.THM(1,1);
            fmax=app.THM(end,1);

            disp(' ');
           
            fstart=0.8*app.THM(1,1);
            fend=app.THM(end,1);

            N=48;
            %
            noct=log(fend/fstart)/log(2);
            np=floor(noct*N);
            %
            freq=zeros(np,1);
            %
            R=2^(1/N);
            %
            freq(1)=fstart;
            for i=2:np
                freq(i)=freq(i-1)*R;
            end
            freq(np)=fend;           
                      
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                  beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,freq,app.part,ffn,damp,app.E,app.MOI);
           
            PSD_in=app.THM;
            
            [app.acc_response_psd]=psd_mult_trans(PSD_in,acc_trans);
            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  );
            
            sz=size(app.acc_response_psd);
            
            fff=app.acc_response_psd(:,1);
            
            %%%%%%%%%%%%%%
        
            app.rv_response_psd=zeros(sz(1),sz(2));
            app.rv_response_psd(:,1)=fff;
            
            app.rd_response_psd=zeros(sz(1),sz(2));
            app.rd_response_psd(:,1)=fff;
            
            app.moment_response_psd=zeros(sz(1),sz(2));
            app.moment_response_psd(:,1)=fff;
            
            app.shear_response_psd=zeros(sz(1),sz(2));
            app.shear_response_psd(:,1)=fff;
            
            app.stress_response_psd=zeros(sz(1),sz(2));
            app.stress_response_psd(:,1)=fff;
        
            %%%%%%%%%%%%%%
            
            disp_rms=0.;
            velox_rms=0;
            bm_rms=0;
            shear_rms=0;
            stress_rms=0.;
            
            vflag=0;
            if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            %    
                vflag=1; 
                [app.rv_response_psd]=psd_mult_trans(PSD_in,rv_trans);
                [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psd(:,1),app.rv_response_psd(:,2)  );
            %
            end   
            
            rflag=0;
            if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
            %    
                rflag=1; 
                [app.rd_response_psd]=psd_mult_trans(PSD_in,rd_trans);
                [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );
            %
            end    
            
            shear_flag=0;
            if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
            %  
                shear_flag=1; 
                [app.shear_response_psd]=psd_mult_trans(PSD_in,shear_trans);
                
                [~,shear_rms]=calculate_PSD_slopes_no(  app.shear_response_psd(:,1),app.shear_response_psd(:,2)  );    
            %
            end
        
            sflag=0;
            if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            %  
                sflag=1; 
                [app.moment_response_psd]=psd_mult_trans(PSD_in,moment_trans);
                
                [~,bm_rms]=calculate_PSD_slopes_no(  app.moment_response_psd(:,1),app.moment_response_psd(:,2)  );    
                       
                app.stress_response_psd(:,1)=        app.moment_response_psd(:,1);    
                app.stress_response_psd(:,2)=(app.cna/app.MOI)^2*app.moment_response_psd(:,2);
                
                [~,stress_rms]=calculate_PSD_slopes_no(  app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 
            %
            end

            if(app.iu==1)
               su='psi';
               sv='in/sec';
               dd='in';
               sb='in-lbf';
               st='psi';
               sh='lbf';
               xu='in';
            else
               su='Pa';
               sv='m/sec';
               dd='mm';
               sb='N-m';
               st='Pa';
               sh='N';
               xu='m';
            %
               disp_rms=disp_rms*1000;
            %
            end

            fprintf('\n Response Overall Levels at x=%g %s \n',app.xx,xu);
            fprintf('\n  Acceleration = %8.4g GRMS \n',accel_rms);
    
            fprintf('  Relative Velocity = %8.4g %s RMS \n',velox_rms,sv);
            fprintf('  Relative displacement = %8.4g %s RMS \n\n',disp_rms,dd);
            fprintf('  Bending moment = %8.4g %s RMS \n',bm_rms,sb);
            fprintf('  Shear Force = %8.4g %s RMS \n',shear_rms,sh);
            fprintf('  Stress = %8.4g %s RMS \n',stress_rms,st);
         
            md=6;
            x_label='Frequency (Hz)';
    
            ylab_acc=sprintf('Accel (G^2/Hz)');
            %
            if(app.iu==1)
                ylab_bm=sprintf('Bending moment ((in-lbf)^2/Hz)');    
                ylab_stress=sprintf('Stress (psi^2/Hz)');
                ylab_rv=sprintf('Rel Vel ((in/sec^2)^2/Hz)');
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
                ylab_sh=sprintf('Shear (lbf^2/Hz)');   
            else
                ylab_bm=sprintf('Bending moment ((N-m)^2/Hz)');     
                ylab_stress=sprintf('Stress (Pa^2/Hz)'); 
                ylab_rv=sprintf('Rel Vel ((m/sec^2)^2/Hz)');    
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
                ylab_sh=sprintf('Shear (N^2/Hz)');      
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes_no( app.THM(:,1),app.THM(:,2) );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            ppp1=app.THM;
            ppp2=app.acc_response_psd;
            
            y_label=ylab_acc;
            %
            if(app.iu==1)
                t_string=sprintf('Acceleration Response PSD  x=%g in',app.xx);
            else
                t_string=sprintf('Acceleration Response PSD  x=%g m',app.xx);    
            end
            %
            leg1=sprintf('Base Input %6.3g GRMS',input_rms);
            leg2=sprintf('Mass Response %6.3g GRMS',accel_rms);
        
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
            app.acceleration=ppp2;
            qsa=sprintf('Acceleration = %7.3g G rms \n\n',accel_rms);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(vflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Relative Velocity Response PSD  x=%g in, %7.3g %s RMS',app.xx,velox_rms,sv);
                else
                    t_string=sprintf('Relative Velocity Response PSD  x=%g m, %7.3g %s RMS',app.xx,velox_rms,sv); 
                end
            
                y_label=ylab_rv;
            %
                ppp=app.rv_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            %
            end

            qsv=sprintf('Rel Velocity = %7.3g in/sec rms \n\n',velox_rms);
            
            if(app.iu==2)
                app.rd_response_psd(:,2)=app.rd_response_psd(:,2)*1.0e+06;
            end

            if(rflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Relative displacement PSD  x=%g in, %6.3g %s RMS',app.xx,disp_rms,dd);
                else
                    t_string=sprintf('Relative displacement PSD  x=%g m,  %6.3g %s RMS',app.xx,disp_rms,dd);       
                end
                
                y_label=ylab_rd;
            %
                ppp=app.rd_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                app.relative_displacement=ppp;
            %
            end

            qsr=sprintf('Rel Displacement = %7.3g in rms \n\n',disp_rms);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(shear_flag==1)
                
                if(app.iu==1)
                    t_string=sprintf('Shear Force PSD  x=%g in,  %7.4g %s RMS',app.xx,shear_rms,sh);
                else
                    t_string=sprintf('Shear Force PSD  x=%g m,  %7.4g %s RMS',app.xx,shear_rms,sh);
                end
                
                y_label=ylab_sh;
            
                ppp=app.shear_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
            end    

            if(sflag==1)
      
                if(app.iu==1)
                    t_string=sprintf('Bending moment PSD  x=%g in,  %7.4g %s RMS',app.xx,bm_rms,sb);
                else
                    t_string=sprintf('Bending moment PSD  x=%g m,  %7.4g %s RMS',app.xx,bm_rms,sb);
                end
                
                y_label=ylab_bm;

                ppp=app.moment_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
                if(app.iu==1)
                    t_string=sprintf('Stress Response PSD  x=%g in,  %7.4g %s RMS',app.xx,stress_rms,su);
                else
                    t_string=sprintf('Stress Response PSD  x=%g m,  %7.4g %s RMS',app.xx,stress_rms,su);
                end
                
                y_label=ylab_stress;

                ppp=app.stress_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                app.bending_stress=ppp;
                qss=sprintf('Bending Stress = %7.3g psi rms',stress_rms);
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string_th{1}='Relative Displacement';
            string_th{2}='Absolute Acceleration';
            string_th{3}='Bending Stress';

            app.OutputFunctionListBox_4.Items=string_th;


            app.ResultsTextArea.Value=sprintf('Response at %7.3g in \n\n %s %s %s %s',app.xx,qsa,qsv,qsr,qss);
            OpenSave(app);

        end

        % Button pushed function: UpdateListButton_psd_2
        function UpdateListButton_psd_2Pushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd_2
        function listbox_method_psd_2ValueChanged(app, event)
            Items = char(app.listbox_method_psd_2.Items);
            im=app.listbox_method_psd_2.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd_2.Value=name;
            end    
 
        end

        % Value changing function: BaseInputAccelGEditField
        function BaseInputAccelGEditFieldValueChanging(app, event)
            CloseResults(app);
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            CloseResults(app);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            CloseResults(app);
        end

        % Button pushed function: UpdateListButton_srs_3
        function UpdateListButton_srs_3Pushed(app, event)

            preprocess(app);

            if(app.kflag==999)
                return;
            end   

            %%%%%%%%%%%%%%%%%%%%%%%%%%         
            
            Items = char(app.listbox_method_srs_2.Items);
            im=app.listbox_method_srs_2.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox_srs_2.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            num_srs=length(app.THM(:,1));
            num_fn=length(app.fn);   
            
            fig_num=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField_2.Value;
            
            t_string=sprintf('SRS Q=%s',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % if(app.fn(1)<app.THM(1,1))
            %   warndlg(' First Natural Frequency < First Specification Frequency '); 
            %   return 
            % end  
            % if(app.fn(num_fn)>app.THM(num_srs,1))
            %   warndlg(' Last Natural Frequency < Last Specification Frequency ');
            %   return
            % end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
              
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  
            
            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);
            
             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
                          
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            nxx=length(app.xx);
            
            if(app.number_modes>num_fn)
                app.number_modes=num_fn;
            end
            
            [ModeShape,~,~]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);  
             
            for i=1:nxx
               
                for j=1:app.number_modes
                    
                    arg=app.beta(j)*app.xx(i);
                    PFM=app.part(j)*ModeShape(arg,app.C(j),app.sq_mass);
                     
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            % disp(' ');
            % disp(' Pseudo Velocity');
            
            if(app.iu==1)
                ss='Pseudo Velocity (in/sec)';          
            else
                ss='Pseudo Velocity (cm/sec)';            
            end
            out_a=sprintf('\n  SRSS \t Abssum');

            ss=sprintf('%s \n',ss,out_a);
            
            % disp(' ');
            % disp(out_a);          
            
            for i=1:nxx
                sv=sprintf(' %8.3g \t %8.3g \n',pv_srss(i),pv_abssum(i));
                ss=sprintf('%s %s',ss,sv);
            end
            
            % disp(' ');
            
            if(app.iu==1)
                s33=' Relative Displacement (in)';
            else
                s33=' Relative Displacement (mm)';               
            end    

            ss=sprintf('\n %s \n %s \n',ss,s33);
                
            out_a=sprintf(' SRSS \t Abssum');

            ss=sprintf('%s \n %s',ss,out_a);
                
            % disp(' ');
            % disp(out_a);
            
            for i=1:nxx
                s44=sprintf(' %8.3g  %8.3g \n',rd_srss(i),rd_abssum(i));
                ss=sprintf('%s \n %s',ss,s44);          
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.iu==1)
                app.ResultsTextArea.Value=sprintf('Response at %7.3g in \n %s',app.xx,ss);
            else
                app.ResultsTextArea.Value=sprintf('Response at %7.3g m \n %s',app.xx,ss);               
            end
            
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button pushed function: UpdateListButton_srs_2
        function UpdateListButton_srs_2Pushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_srs_2
        function listbox_method_srs_2ValueChanged(app, event)
            Items = char(app.listbox_method_srs_2.Items);
            im=app.listbox_method_srs_2.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               app.SelectInputArrayListBox_srs_2.Value=name;
            end    
        end

        % Button pushed function: Rainflow
        function RainflowPushed(app, event)
            rainflow_app
        end

        % Value changed function: ResponseLocationinEditField_base
        function ResponseLocationinEditField_baseValueChanged(app, event)
            q=str2num(app.ResponseLocationinEditField_base.Value);
            if(q>app.L)
                app.ResponseLocationinEditField_base.Value=sprintf('%g',app.L);
            end          
        end

        % Value changed function: SelectInputArrayListBox_psd_2
        function SelectInputArrayListBox_psd_2ValueChanged(app, event)
            CloseResults(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
           
            ChangeMaterials(app);
            ChangeCrossSection(app);
        end

        % Button pushed function: CalculateAppliedForce_2
        function CalculateAppliedForce_2ButtonPushed(app, event)
            preprocess_pforce(app);

            if(app.kflag==999)
                return;
            end   

            Items = char(app.listbox_method_arb_force.Items);
            im=app.listbox_method_arb_force.Value;
            [n]=find_index(Items,im);
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_arb_force.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end   

            force_time(app);

        end

        % Button pushed function: CalculateAppliedForce
        function CalculateAppliedForceButtonPushed(app, event)
            
            preprocess_pforce(app);

            if(app.kflag==999)
                return;
            end    
      
            fstart=app.StartFrequencyHzEditField.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end

            mass=app.sq_mass^2;

            app.number_modes=str2num(app.NumberofModesListBox_pforce.Value);

            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            bbeta=app.beta(1:app.number_modes);
            CC=app.C(1:app.number_modes);
            
            [app.acceleration,app.velocity,app.displacement,app.bending_stress]=...
                fixed_free_point_force_end_frf(fstart,fend,ffn,ddm,mass,app.L,bbeta,CC,app.iu,app.E,app.MOI,app.cna);

            sq={'Acceleration free end','Velocity free end','Displacement free end','Bending Stress fixed end'};
            app.OutputFunctionListBox_force.Items=sq;
            
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed(app, event)
            
            app.analysis='Trans';

            preprocess(app);

            if(app.kflag==999)
                return;
            end    

            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
               
            N=48;
            fmin=fstart;
            [f,~,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            ppart=app.part(1:app.number_modes);
               
            
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI);   
                  
            stress_trans=moment_trans;
               
            fprintf('  cna=%8.4g  I=%8.4g  cna/I=%8.4g \n',app.cna,app.MOI,app.cna/app.MOI);
               
            stress_trans(:,2)=stress_trans(:,2)*app.cna/app.MOI;
                
            [maxF,maxH]=find_max_alt(rd_trans);
            [maxFV,maxHV]=find_max_alt(rv_trans);    
            [maxFA,maxHA]=find_max_alt(acc_trans);  
            [maxFBM,maxHBM]=find_max_alt(moment_trans);
            [maxFSH,maxHSH]=find_max_alt(shear_trans);    
            [maxFST,maxHST]=find_max_alt(stress_trans);      
            
            md=6;
            x_label='Frequency (Hz)';
                
            fprintf('\n Response Location: x=%g inches\n\n',app.xx);
       
            fmin=fstart;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g inch',app.xx);
                y_label='Rel Disp (in)/ Base Accel (G)';
            else
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g m',app.xx);
                y_label='Rel Disp (m)/ Base Accel (G)';        
            end
          
            try
                ppp=rd_trans;
                if(min(ppp(:,2))>1.e-90)
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                end    
            catch
            end
           
            disp(' ');
            if(app.iu==1)
                ssr=sprintf('Rel Disp = \n  %7.3g (in/G)  at %7.3g Hz \n\n',maxH,maxF);
            else
                ssr=sprintf('Rel Disp = \n  %7.3g (m/G)  at %7.3g Hz \n\n',maxH,maxF);       
            end
            fprintf(ssr);
            %
            app.relative_disp=rd_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g inch',app.xx);
                    y_label='Rel Vel (in/sec)/ Base Accel (G)';
                else
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g m',app.xx);
                    y_label='Rel Vel (m/sec)/ Base Accel (G)';        
                end
                
                try
                    ppp=rv_trans;
                    if(min(ppp(:,2))>1.e-90)
                        [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    end
                catch
                end
            %
                disp(' ');
                try
                
                    if(app.iu==1)
                        ssv=sprintf('Rel Vel = \n  %7.3g ((in/sec)/G)  at %6.3g Hz \n\n',maxHV,maxFV);
                    else
                        ssv=sprintf('Rel Vel = \n  %7.3g ((m/sec)/G)  at %6.3g Hz \n\n',maxHV,maxFV);       
                    end
                    fprintf(ssv)
                catch 
                end
            %
                app.relative_vel=rv_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g inch',app.xx);
                else
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g m',app.xx);        
                end
                y_label='Absolute Accel / Base Accel';    
                
                ppp=acc_trans; 
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    
                ssa=sprintf('Absolute Accel = \n  %8.4g (G/G)  at %7.3g Hz \n\n',maxHA,maxFA);
                fprintf(ssa);

                ss=sprintf('%s %s %s',ssa,ssv,ssr);

            %
                app.absolute_accel=acc_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(app.iu==1)
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g inch',app.xx);
                    y_label='moment (lbf-in)/ Base Accel (G)';
                else
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g m',app.xx);
                    y_label='moment (N-m)/ Base Accel (G)';        
                end
            %
                ppp=moment_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(app.iu==1)
                    fprintf('  max Bending moment FRF = %9.5g (lbf-in/G)  at %6.3g Hz \n',maxHBM,maxFBM);
                else
                    fprintf('  max Bending moment FRF = %9.5g (N-m/G)  at %6.3g Hz \n',maxHBM,maxFBM);       
                end
            %
                app.bending_moment=moment_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(app.iu==1)
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g inch',app.xx);
                    y_label='Shear (lbf)/ Base Accel (G)';
                else
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g m',app.xx);
                    y_label='Shear (N)/ Base Accel (G)';        
                end
            %
                ppp=shear_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(app.iu==1)
                    fprintf('  max Shear Force FRF = %9.5g (lbf/G) at %8.4g Hz \n',maxHSH,maxFSH);
                else
                    fprintf('  max Shear Force FRF = %9.5g (N/G) at %8.4g Hz \n',maxHSH,maxFSH);       
                end
            %
                app.shear_force=shear_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                ppp=stress_trans;
            
                if(app.iu==1)
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g inch',app.xx);
                    y_label='Stress (psi) / Base Accel (G)';
                else
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g m',app.xx);
                    y_label='Stress (Pa) / Base Accel (G)';        
                end
            
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
                    
                if(app.iu==1)
                    ss1=sprintf('Bending Stress = \n  %7.4g (psi/G)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g in \n\n %s %s',app.xx,ss,ss1);
                else
                    ss1=sprintf('Bending Stress = \n  %7.4g (Pa/G)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g m \n\n %s %s',app.xx,ss,ss1);
                end

                fprintf(ss1);
       
            %
                app.bending_stress=stress_trans;

            app.ResultsTextArea.Value=ss;

            string_th{1}='Relative Displacement';
            string_th{2}='Relative Velocity';
            string_th{3}='Absolute Acceleration';
            string_th{4}='Bending Moment';
            string_th{5}='Bending Stress';
            string_th{6}='Shear Force';

            app.OutputFunctionListBox_4.Items=string_th;

            OpenSave(app);

            nnum=21;
            nnumm1=nnum-1;

            xa=app.L*((1:nnum)-1)/nnumm1;

            sz=size(rv_trans);
            
            RV=zeros(sz(1),11);
            MM=zeros(sz(1),11); 
            RVmax=zeros(sz(1),1); 
            MMmax=zeros(sz(1),1); 

            for i=1:nnum

                [~,rv_trans,~,moment_trans,~]=...
                    beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,xa(i),app.iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI);   

                RV(:,i)=rv_trans(:,2);
                MM(:,i)=moment_trans(:,2);
                  
            end

            for i=1:sz(1)
                RVmax(i)=max(RV(i,:));
                MMmax(i)=max(MM(i,:));
            end    

            STmax=MMmax*app.cna/app.MOI;

            STmax=[rv_trans(:,1) STmax];
            RVmax=[rv_trans(:,1) RVmax];

            assignin('base','STmax', STmax); 
            assignin('base','RVmax', RVmax); 


        end

        % Button down function: AppliedPointForceTab
        function AppliedPointForceTabButtonDown(app, event)
            if(app.bc_type~=1)
                warndlg('Applied Point Force unavailable for this BC case');
                app.TabGroup.SelectedTab=app.GeometryBCsTab;
                return;
            end    
        end

        % Button pushed function: Rainflow_2
        function Rainflow_2ButtonPushed(app, event)
            rainflow_app
        end

        % Button pushed function: SaveTransButton_3
        function SaveTransButton_3Pushed(app, event)
            Items = char(app.OutputFunctionListBox_force.Items);
            im=app.OutputFunctionListBox_force.Value;
            [n]=find_index(Items,im);

                if(n==1)
                    data=app.acceleration;
                end
                if(n==2)
                    data=app.velocity;
                end
                if(n==3)
                    data=app.displacement;
                end 
                if(n==4)
                    data=app.bending_stress;
                end

            output_name=strtrim(app.OutputArrayNameEditField_force.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved'); 

        end

        % Button pushed function: UpdateListButton_arb_force
        function UpdateListButton_arb_forcePushed(app, event)
            update_arb_force(app);
        end

        % Value changed function: listbox_method_arb_force
        function listbox_method_arb_forceValueChanged(app, event)
            Items = char(app.listbox_method_arb_force.Items);
            im=app.listbox_method_arb_force.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_arb_force(app);
               app.SelectInputArrayListBox_arb_force.Value=name;
            end    

        end

        % Value changed function: NumberofModesListBox_pforce
        function NumberofModesListBox_pforceValueChanged(app, event)
            CloseResults(app);
        end

        % Value changed function: NumberofModesListBox_dforce
        function NumberofModesListBox_dforceValueChanged(app, event)
            CloseResults(app);            
        end

        % Button pushed function: CalculateBaseInputTrans_dforce
        function CalculateBaseInputTrans_dforceButtonPushed2(app, event)
            
            CloseResults(app);

            app.analysis='Trans';
            
            preprocess_dforce(app);

            if(app.kflag==999)
                return;
            end    

            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField_dforce.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField_dforce.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
               
            N=48;
            fmin=fstart;
            [f,~,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            ppart=app.part(1:app.number_modes);
               
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                beam_bending_trans_core_dforce(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI,app.mass_per_length);   
                  
            stress_trans=moment_trans;
               
            fprintf('  cna=%8.4g  I=%8.4g  cna/I=%8.4g \n',app.cna,app.MOI,app.cna/app.MOI);
               
            stress_trans(:,2)=stress_trans(:,2)*app.cna/app.MOI;
                
            [maxF,maxH]=find_max_alt(rd_trans);
            [maxFV,maxHV]=find_max_alt(rv_trans);    
            [maxFA,maxHA]=find_max_alt(acc_trans);  
            [maxFBM,maxHBM]=find_max_alt(moment_trans);
            [maxFSH,maxHSH]=find_max_alt(shear_trans);    
            [maxFST,maxHST]=find_max_alt(stress_trans);   

            md=6;
            x_label='Frequency (Hz)';
                
            fprintf('\n Response Location: x=%g inches\n\n',app.xx);
       
            fmin=fstart;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                t_string=sprintf('Displacement Frequency Response Function  x=%g inch',app.xx);
                y_label='Disp (in)/ Distributed Force  (lbf/in)';
            else
                t_string=sprintf('Displacement Frequency Response Function  x=%g m',app.xx);
                y_label='Rel Disp (m)/ Distributed Force (N/m)';        
            end
          
            try
                ppp=rd_trans;
                if(min(ppp(:,2))>1.e-90)
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                end    
            catch
            end
           
            disp(' ');
            if(app.iu==1)
                ssr=sprintf('Disp = %7.3g (in/lbf/in)  at %7.3g Hz \n\n',maxH,maxF);
            else
                ssr=sprintf('Disp = %7.3g (m/N/m)  at %7.3g Hz \n\n',maxH,maxF);       
            end
            fprintf(ssr);
            %
            app.relative_disp=rd_trans;
            
            if(app.iu==1)
                    t_string=sprintf('Velocity Frequency Response Function  x=%g inch',app.xx);
                    y_label='Vel (in/sec)/ Distributed Force (lbf/in)';
            else
                    t_string=sprintf('Velocity Frequency Response Function  x=%g m',app.xx);
                    y_label='Vel (m/sec)/ Distributed Force (N/m)';        
            end
                
            try
                    ppp=rv_trans;
                    if(min(ppp(:,2))>1.e-90)
                        [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    end
            catch
            end
            %
            disp(' ');
            
            try
                
                    if(app.iu==1)
                        ssv=sprintf('Vel = %7.3g ((in/sec)/(lbf/in))  at %6.3g Hz \n\n',maxHV,maxFV);
                    else
                        ssv=sprintf('Vel = %7.3g ((m/sec)/(N/m))  at %6.3g Hz \n\n',maxHV,maxFV);       
                    end
                    fprintf(ssv)
            catch 
            end
            %
            app.relative_vel=rv_trans;

            if(app.iu==1)
		                   y_label='Accel (G) / Distributed Force  (lbf/in)';    
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g inch',app.xx);
                    ssa=sprintf('Accel = %8.4g (G/lbf/in)  at %7.3g Hz \n\n',maxHA,maxFA);
            else
		                   y_label='Accel (G) / Distributed Force  (N/m)';    
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g m',app.xx);     
                    ssa=sprintf('Accel = %8.4g (G/lbf/m)  at %7.3g Hz \n\n',maxHA,maxFA);
                    
            end

            fprintf(ssa);
                    
            ppp=acc_trans; 
               
            try
                if(max(ppp(:,2))>1.0e-20)
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                end    
            catch
            end


            ss=sprintf('%s %s %s',ssa,ssv,ssr);

            %
            app.absolute_accel=acc_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g inch',app.xx);
                    y_label='Moment (lbf-in)/ Distributed Force (lbf/in)';
            else
                    t_string=sprintf('Bending Moment Frequency Response Function  x=%g m',app.xx);
                    y_label='Moment (N-m)/ Distributed Force (N/m)';        
            end
            %
            ppp=moment_trans;
            try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end
            %
            if(app.iu==1)
                    fprintf('  max Bending moment FRF = %9.5g (lbf-in/lbf/in)  at %6.3g Hz \n',maxHBM,maxFBM);
            else
                    fprintf('  max Bending moment FRF = %9.5g (N-m/N/m)  at %6.3g Hz \n',maxHBM,maxFBM);       
            end
            %
            app.bending_moment=moment_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g inch',app.xx);
                    y_label='Shear (lbf)/ Distributed Force (lbf/in)';
            else
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g m',app.xx);
                    y_label='Shear (N)/ Distributed Force (N/m)';        
            end
            %
            ppp=shear_trans;
            try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end
            %
            if(app.iu==1)
                    fprintf('  max Shear Force FRF = %9.5g (lbf/lbf/in) at %8.4g Hz \n',maxHSH,maxFSH);
            else
                    fprintf('  max Shear Force FRF = %9.5g (N/N/m) at %8.4g Hz \n',maxHSH,maxFSH);       
            end
            %
            app.shear_force=shear_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp=stress_trans;
            
            if(app.iu==1)
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g inch',app.xx);
                    y_label='Stress (psi) / Distributed Force (lbf/in)';
            else
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g m',app.xx);
                    y_label='Stress (Pa) / Distributed Force (N/m)';        
            end
            
            try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end
                    
            if(app.iu==1)
                    ss1=sprintf('Bending Stress = \n  %7.4g (psi/lbf/in)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g in \n\n %s %s',app.xx,ss,ss1);
            else
                    ss1=sprintf('Bending Stress = \n  %7.4g (Pa/N/m)  at %6.3g Hz \n',maxHST,maxFST);
                    ss=sprintf('Peak Responses at %8.4g m \n\n %s %s',app.xx,ss,ss1);
            end

            fprintf(ss1);
       
            app.ResultsTextArea_dforce.Value=ss;

            app.bending_stress=stress_trans;

            OpenSave_dforce(app);

            % app.OutputFunctionListBox_4.Items=string_th;


        end

        % Button pushed function: Calculate_psd_dforce
        function Calculate_psd_dforcePushed(app, event)
            app.analysis='PSD';

            preprocess_dforce(app);

            if(app.kflag==999)
                return;
            end   

            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            fig_num=1;
            
            Items = char(app.listbox_method_psd_dforce.Items);
            im=app.listbox_method_psd_dforce.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox_psd_dforce.Value;
                     app.THM=evalin('base',FS);
                     app.THM(:,2)=app.THM(:,2)*app.cwidth^2;
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end    

            fstart=0.8*app.THM(1,1);
            fend=app.THM(end,1);

            fmin=fstart;
            fmax=fend;
               
            N=48;
            
            [f,~,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            ffn=app.fn(1:app.number_modes);
            ddm=app.dm(1:app.number_modes);
            ppart=app.part(1:app.number_modes);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    acc_trans
            %        
                      
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                  beam_bending_trans_core_dforce(app.beta,app.C,app.sq_mass,app.xx,app.iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI,app.mass_per_length); 
           
            PSD_in=app.THM;
            
            [app.acc_response_psd]=psd_mult_trans(PSD_in,acc_trans);
            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  );
            
            sz=size(app.acc_response_psd);
            
            fff=app.acc_response_psd(:,1);
            
            %%%%%%%%%%%%%%
        
            app.rv_response_psd=zeros(sz(1),sz(2));
            app.rv_response_psd(:,1)=fff;
            
            app.rd_response_psd=zeros(sz(1),sz(2));
            app.rd_response_psd(:,1)=fff;
            
            app.moment_response_psd=zeros(sz(1),sz(2));
            app.moment_response_psd(:,1)=fff;
            
            app.shear_response_psd=zeros(sz(1),sz(2));
            app.shear_response_psd(:,1)=fff;
            
            app.stress_response_psd=zeros(sz(1),sz(2));
            app.stress_response_psd(:,1)=fff;
        
            %%%%%%%%%%%%%%
            
            disp_rms=0.;
            velox_rms=0;
            bm_rms=0;
            shear_rms=0;
            stress_rms=0.;
            
            vflag=0;
            if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            %    
                vflag=1; 
                [app.rv_response_psd]=psd_mult_trans(PSD_in,rv_trans);
                [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psd(:,1),app.rv_response_psd(:,2)  );
            %
            end   
            
            rflag=0;
            if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
            %    
                rflag=1; 
                [app.rd_response_psd]=psd_mult_trans(PSD_in,rd_trans);
                [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );
            %
            end    
            
            shear_flag=0;
            if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
            %  
                shear_flag=1; 
                [app.shear_response_psd]=psd_mult_trans(PSD_in,shear_trans);
                
                [~,shear_rms]=calculate_PSD_slopes_no(  app.shear_response_psd(:,1),app.shear_response_psd(:,2)  );    
            %
            end
        
            sflag=0;
            if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            %  
                sflag=1; 
                [app.moment_response_psd]=psd_mult_trans(PSD_in,moment_trans);
                
                [~,bm_rms]=calculate_PSD_slopes_no(  app.moment_response_psd(:,1),app.moment_response_psd(:,2)  );    
                       
                app.stress_response_psd(:,1)=        app.moment_response_psd(:,1);    
                app.stress_response_psd(:,2)=(app.cna/app.MOI)^2*app.moment_response_psd(:,2);
                
                [~,stress_rms]=calculate_PSD_slopes_no(  app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 
            %
            end

            if(app.iu==1)
               su='psi';
               sv='in/sec';
               dd='in';
               sb='in-lbf';
               st='psi';
               sh='lbf';
               xu='in';
            else
               su='Pa';
               sv='m/sec';
               dd='mm';
               sb='N-m';
               st='Pa';
               sh='N';
               xu='m';
            %
               disp_rms=disp_rms*1000;
            %
            end

            fprintf('\n Response Overall Levels at x=%g %s \n',app.xx,xu);
            fprintf('\n  Acceleration = %8.4g GRMS \n',accel_rms);
    
            fprintf(' Velocity = %8.4g %s RMS \n',velox_rms,sv);
            fprintf(' Displacement = %8.4g %s RMS \n\n',disp_rms,dd);
            fprintf(' Bending moment = %8.4g %s RMS \n',bm_rms,sb);
            fprintf('  Shear Force = %8.4g %s RMS \n',shear_rms,sh);
            fprintf('  Stress = %8.4g %s RMS \n',stress_rms,st);
         
            md=6;
            x_label='Frequency (Hz)';
    
            ylab_acc=sprintf('Accel (G^2/Hz)');
            %
            if(app.iu==1)
                ylab_bm=sprintf('Bending moment ((in-lbf)^2/Hz)');    
                ylab_stress=sprintf('Stress (psi^2/Hz)');
                ylab_rv=sprintf('Vel ((in/sec^2)^2/Hz)');
                ylab_rd=sprintf('Disp (inch^2/Hz)');
                ylab_sh=sprintf('Shear (lbf^2/Hz)');   
            else
                ylab_bm=sprintf('Bending moment ((N-m)^2/Hz)');     
                ylab_stress=sprintf('Stress (Pa^2/Hz)'); 
                ylab_rv=sprintf('Vel ((m/sec^2)^2/Hz)');    
                ylab_rd=sprintf('Disp (mm^2/Hz)');
                ylab_sh=sprintf('Shear (N^2/Hz)');      
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
       
            ppp=app.acc_response_psd;
            y_label=ylab_acc;
            t_string=sprintf('Acceleration Response PSD  x=%g in, %7.3g GRMS',app.xx,accel_rms);
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            qsa=sprintf('Acceleration = %7.3g GRMS \n\n',accel_rms);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(vflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Velocity Response PSD  x=%g in, %7.3g %s RMS',app.xx,velox_rms,sv);
                else
                    t_string=sprintf('Velocity Response PSD  x=%g m, %7.3g %s RMS',app.xx,velox_rms,sv); 
                end
            
                y_label=ylab_rv;
            %
                ppp=app.rv_response_psd;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
            end

            qsv=sprintf('Velocity = %7.3g in/sec rms \n\n',velox_rms);
            
            if(app.iu==2)
                app.rd_response_psd(:,2)=app.rd_response_psd(:,2)*1.0e+06;
            end

            if(rflag==1)
            
                if(app.iu==1)
                    t_string=sprintf('Displacement PSD  x=%g in, %6.3g %s RMS',app.xx,disp_rms,dd);
                else
                    t_string=sprintf('Displacement PSD  x=%g m,  %6.3g %s RMS',app.xx,disp_rms,dd);       
                end
                
                y_label=ylab_rd;
            %
                ppp=app.rd_response_psd;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
                app.relative_displacement=ppp;
            %
            end

            qsr=sprintf('Displacement = %7.3g in rms \n\n',disp_rms);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(shear_flag==1)
                
                if(app.iu==1)
                    t_string=sprintf('Shear Force PSD  x=%g in,  %7.4g %s RMS',app.xx,shear_rms,sh);
                else
                    t_string=sprintf('Shear Force PSD  x=%g m,  %7.4g %s RMS',app.xx,shear_rms,sh);
                end
                
                y_label=ylab_sh;
            
                ppp=app.shear_response_psd;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                catch
                end
            end    

            if(sflag==1)
      
                if(app.iu==1)
                    t_string=sprintf('Bending moment PSD  x=%g in,  %7.4g %s RMS',app.xx,bm_rms,sb);
                else
                    t_string=sprintf('Bending moment PSD  x=%g m,  %7.4g %s RMS',app.xx,bm_rms,sb);
                end
                
                y_label=ylab_bm;

                ppp=app.moment_response_psd;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                catch
                end

                if(app.iu==1)
                    t_string=sprintf('Stress Response PSD  x=%g in,  %7.4g %s RMS',app.xx,stress_rms,su);
                else
                    t_string=sprintf('Stress Response PSD  x=%g m,  %7.4g %s RMS',app.xx,stress_rms,su);
                end
                
                y_label=ylab_stress;

                ppp=app.stress_response_psd;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
                app.bending_stress=ppp;
                qss=sprintf('Bending Stress = %7.3g psi rms',stress_rms);
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string_th{1}='Displacement';
            string_th{2}='Acceleration';
            string_th{3}='Bending Stress';

            app.OutputFunctionListBox_dforce.Items=string_th;

            app.ResultsTextArea_dforce.Value=sprintf('Response at %7.3g in \n\n %s %s %s %s',app.xx,qsa,qsv,qsr,qss);
            
            OpenSave_dforce(app);
        end

        % Button pushed function: UpdateListButton_psd_dforce
        function UpdateListButton_psd_dforcePushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd_dforce
        function listbox_method_psd_dforceValueChanged(app, event)
         
            Items = char(app.listbox_method_psd_dforce.Items);
            im=app.listbox_method_psd_dforce.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd_dforce.Value=name;
            end    
        end

        % Value changed function: ResponseLocationinEditField_dforce
        function ResponseLocationinEditField_dforceValueChanged(app, event)

            CloseResults(app);
        end

        % Value changed function: BeamLengthinEditField_dforce
        function BeamLengthinEditField_dforceValueChanged(app, event)

            CloseResults(app);
        end

        % Value changed function: StartFrequencyHzEditField_dforce
        function StartFrequencyHzEditField_dforceValueChanged(app, event)
     
            CloseResults(app);
        end

        % Value changed function: EndFrequencyHzEditField_dforce
        function EndFrequencyHzEditField_dforceValueChanged(app, event)
       
            CloseResults(app);
        end

        % Button pushed function: SaveTransButton_dforce
        function SaveTransButton_dforcePushed(app, event)

            Items = strtrim(app.OutputFunctionListBox_dforce.Items);
            im = strtrim(app.OutputFunctionListBox_dforce.Value);
            n = find(strcmp(Items, im));

            app.analysis

            if(strcmp(app.analysis,'Trans'))

                if(n==1)
                    data=app.relative_disp;
                end
                if(n==2)
                    data=app.relative_vel;
                end
                if(n==3)
                    data=app.absolute_accel;
                end 
                if(n==4)
                    data=app.bending_moment;
                end
                if(n==5)
                    data=app.bending_stress;
                end
                if(n==6)
                    data=app.shear_force;
                end
            end
      
            if(strcmp(app.analysis,'PSD'))
                if(contains(im,'Disp'))
                    data=app.relative_displacement;
                end
                if(contains(im,'Accel'))
                    data=app.acceleration;
                end 
                if(contains(im,'Stress'))
                    data=app.bending_stress;
                end
            end                 

            output_name=strtrim(app.OutputArrayNameEditField_dforce.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Value changed function: SelectInputArrayListBox_psd_dforce
        function SelectInputArrayListBox_psd_dforceValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_force_sine
        function CalculateButton_force_sinePushed(app, event)
            
            preprocess_pforce(app);

            if(app.kflag==999)
                return;
            end   
            
            F=str2num(app.ForcelbfEditField.Value);
            dur=str2num(app.DurationsecEditField_force_sine.Value);
            sr=str2num(app.SampleRateEditField_force.Value);
            freq=str2num(app.FreqEditField.Value);

            dt=1/sr;

            num=floor(dur/dt);

            t=(0:num-1)*dt;

            t=fix_size(t);

            amp=F*sin(2*pi*freq*t);

            %%%

            app.THM=[t amp];

            force_time(app);


        end

        % Button pushed function: SetfnButton
        function SetfnButtonPushed(app, event)
            app.FreqEditField.Value=sprintf('%7.4g',app.fn(1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeamBendingContinuousModelUIFigure = uifigure('Visible', 'off');
            app.BeamBendingContinuousModelUIFigure.Name = 'Beam Bending Continuous Model';
            app.TabGroup = uitabgroup(app.BeamBendingContinuousModelUIFigure);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Fixed';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'text');
            app.LengthinEditField.ValueChangingFcn = createCallbackFcn(app, @LengthinEditFieldValueChanging, true);
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_1.ValueChangingFcn = createCallbackFcn(app, @EditField_1ValueChanging, true);
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.UnitsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryBCsTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.MaterialLabel = uilabel(app.MaterialTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.MassDensitylbmin3Label = uilabel(app.MaterialTab);
            app.MassDensitylbmin3Label.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.NaturalFreqTab = uitab(app.TabGroup);
            app.NaturalFreqTab.Title = 'Natural Freq';
            app.CalculateNaturalFrequenciesButton = uibutton(app.NaturalFreqTab, 'push');
            app.CalculateNaturalFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesButtonPushed, true);
            app.CalculateNaturalFrequenciesButton.Text = 'Calculate Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.NaturalFreqTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFreqTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFreqTab);
            app.UITable_fn = uitable(app.NaturalFreqTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            app.UITable_fn.RowName = {};
            app.MassResultsTextAreaLabel = uilabel(app.NaturalFreqTab);
            app.MassResultsTextAreaLabel.Text = 'Mass Results';
            app.MassResultsTextArea = uitextarea(app.NaturalFreqTab);
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.BaseExcitationTab = uitab(app.TabGroup);
            app.BaseExcitationTab.Title = 'Base Excitation';
            app.BaseExcitationTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationTabButtonDown, true);
            app.NumberofModesLabel = uilabel(app.BaseExcitationTab);
            app.NumberofModesLabel.Text = {'Number of '; 'Modes'};
            app.NumberofModesListBox_base = uilistbox(app.BaseExcitationTab);
            app.NumberofModesListBox_base.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_base.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBox_baseValueChanged, true);
            app.NumberofModesListBox_base.Value = '1';
            app.ImageBeamBase_2 = uiimage(app.BaseExcitationTab);
            app.TabGroup2 = uitabgroup(app.BaseExcitationTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.TransTab = uitab(app.TabGroup2);
            app.TransTab.Title = 'Trans';
            app.CalculateBaseInputTrans = uibutton(app.TransTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.StartFrequencyHzEditFieldLabel = uilabel(app.TransTab);
            app.StartFrequencyHzEditFieldLabel.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.StartFrequencyHzEditField.Value = 10;
            app.EndFrequencyHzEditFieldLabel = uilabel(app.TransTab);
            app.EndFrequencyHzEditFieldLabel.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.EndFrequencyHzEditField.Value = 2000;
            app.SineTab = uitab(app.TabGroup2);
            app.SineTab.Title = 'Sine';
            app.SettoFirstfnButton_2 = uibutton(app.SineTab, 'push');
            app.SettoFirstfnButton_2.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButton_2Pushed, true);
            app.SettoFirstfnButton_2.Text = 'Set to First fn';
            app.ExcitationFrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.ExcitationFrequencyHzEditFieldLabel.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzEditField = uieditfield(app.SineTab, 'text');
            app.ExcitationFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @ExcitationFrequencyHzEditFieldValueChanging, true);
            app.BaseAccelGEditFieldLabel = uilabel(app.SineTab);
            app.BaseAccelGEditFieldLabel.Text = 'Base Accel (G)';
            app.BaseAccelGEditField = uieditfield(app.SineTab, 'text');
            app.BaseAccelGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelGEditFieldValueChanging, true);
            app.BaseAccelGEditField.Value = '1';
            app.CalculateBaseInputSine_2 = uibutton(app.SineTab, 'push');
            app.CalculateBaseInputSine_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSine_2ButtonPushed, true);
            app.CalculateBaseInputSine_2.Text = 'Calculate';
            app.HalfSineTab = uitab(app.TabGroup2);
            app.HalfSineTab.Title = 'Half-Sine';
            app.CalculateButton = uibutton(app.HalfSineTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BaseInputAccelGLabel = uilabel(app.HalfSineTab);
            app.BaseInputAccelGLabel.Text = {'Base Input '; 'Accel (G)'};
            app.BaseInputAccelGEditField = uieditfield(app.HalfSineTab, 'text');
            app.BaseInputAccelGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseInputAccelGEditFieldValueChanging, true);
            app.DurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.HalfSineTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.PSDTab = uitab(app.TabGroup2);
            app.PSDTab.Title = 'PSD';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel_2 = uilabel(app.PSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel_2.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.listbox_method_psd_2 = uilistbox(app.PSDTab);
            app.listbox_method_psd_2.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd_2.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psd_2ValueChanged, true);
            app.listbox_method_psd_2.Tag = 'listbox_method';
            app.listbox_method_psd_2.Value = 'Array Preloaded in Matlab';
            app.text1_psd_2 = uilabel(app.PSDTab);
            app.text1_psd_2.Tag = 'text1';
            app.text1_psd_2.Text = 'Select Data Input Method';
            app.UpdateListButton_psd_2 = uibutton(app.PSDTab, 'push');
            app.UpdateListButton_psd_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psd_2Pushed, true);
            app.UpdateListButton_psd_2.Text = 'Update List';
            app.SelectInputArrayListBox_2Label_3 = uilabel(app.PSDTab);
            app.SelectInputArrayListBox_2Label_3.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd_2 = uilistbox(app.PSDTab);
            app.SelectInputArrayListBox_psd_2.Items = {'Item 1'};
            app.SelectInputArrayListBox_psd_2.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_psd_2ValueChanged, true);
            app.UpdateListButton_psd_3 = uibutton(app.PSDTab, 'push');
            app.UpdateListButton_psd_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psd_3Pushed, true);
            app.UpdateListButton_psd_3.Text = 'Calculate';
            app.ArbitraryTab = uitab(app.TabGroup2);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.CalculateBaseInputArbitrary_2 = uibutton(app.ArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitrary_2ButtonPushed, true);
            app.CalculateBaseInputArbitrary_2.Text = 'Calculate';
            app.listbox_method_2 = uilistbox(app.ArbitraryTab);
            app.listbox_method_2.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_2.ValueChangedFcn = createCallbackFcn(app, @listbox_method_2ValueChanged, true);
            app.listbox_method_2.Tag = 'listbox_method';
            app.listbox_method_2.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.ArbitraryTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.UpdateListButton_2 = uibutton(app.ArbitraryTab, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.SelectInputArrayListBox_3Label = uilabel(app.ArbitraryTab);
            app.SelectInputArrayListBox_3Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_3 = uilistbox(app.ArbitraryTab);
            app.SelectInputArrayListBox_3.Items = {'Item 1'};
            app.TheinputarraymusthavetwocolumnsTimesecAccelGLabel = uilabel(app.ArbitraryTab);
            app.TheinputarraymusthavetwocolumnsTimesecAccelGLabel.Text = {' The input array must have '; ' two columns:  '; ''; '   Time (sec) & Accel (G)'; ''};
            app.MDOFSRSTab = uitab(app.TabGroup2);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel_2 = uilabel(app.MDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel_2.Text = {'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)'; ''};
            app.listbox_method_srs_2 = uilistbox(app.MDOFSRSTab);
            app.listbox_method_srs_2.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_srs_2.ValueChangedFcn = createCallbackFcn(app, @listbox_method_srs_2ValueChanged, true);
            app.listbox_method_srs_2.Tag = 'listbox_method';
            app.listbox_method_srs_2.Value = 'Array Preloaded in Matlab';
            app.text1_srs_2 = uilabel(app.MDOFSRSTab);
            app.text1_srs_2.Tag = 'text1';
            app.text1_srs_2.Text = 'Select Data Input Method';
            app.UpdateListButton_srs_2 = uibutton(app.MDOFSRSTab, 'push');
            app.UpdateListButton_srs_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srs_2Pushed, true);
            app.UpdateListButton_srs_2.Text = 'Update List';
            app.SelectInputArrayListBox_2Label_4 = uilabel(app.MDOFSRSTab);
            app.SelectInputArrayListBox_2Label_4.Text = 'Select Input Array';
            app.SelectInputArrayListBox_srs_2 = uilistbox(app.MDOFSRSTab);
            app.SelectInputArrayListBox_srs_2.Items = {'Item 1'};
            app.UpdateListButton_srs_3 = uibutton(app.MDOFSRSTab, 'push');
            app.UpdateListButton_srs_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srs_3Pushed, true);
            app.UpdateListButton_srs_3.Text = 'Calculate';
            app.SpecificationQEditField_2Label = uilabel(app.MDOFSRSTab);
            app.SpecificationQEditField_2Label.Text = 'Specification Q';
            app.SpecificationQEditField_2 = uieditfield(app.MDOFSRSTab, 'text');
            app.SpecificationQEditField_2.Value = '10';
            app.SaveTransButton_2 = uibutton(app.BaseExcitationTab, 'push');
            app.SaveTransButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveTransButton_2Pushed, true);
            app.SaveTransButton_2.Text = 'Save';
            app.OutputFunctionListBox_4Label = uilabel(app.BaseExcitationTab);
            app.OutputFunctionListBox_4Label.Text = 'Output Function';
            app.OutputFunctionListBox_4 = uilistbox(app.BaseExcitationTab);
            app.OutputFunctionListBox_4.Items = {'Relative Displacement', 'Relative Velocity', 'Absolute Acceleration', 'Bending Moment', 'Bending Stress', 'Shear Force'};
            app.OutputFunctionListBox_4.Value = 'Relative Displacement';
            app.OutputArrayNameEditField_4Label = uilabel(app.BaseExcitationTab);
            app.OutputArrayNameEditField_4Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_4 = uieditfield(app.BaseExcitationTab, 'text');
            app.ResultsTextAreaLabel = uilabel(app.BaseExcitationTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.BaseExcitationTab);
            app.ResponseLocationinLabel_base = uilabel(app.BaseExcitationTab);
            app.ResponseLocationinLabel_base.Text = {'Response '; 'Location (in)'};
            app.ResponseLocationinEditField_base = uieditfield(app.BaseExcitationTab, 'text');
            app.ResponseLocationinEditField_base.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationinEditField_baseValueChanged, true);
            app.ResponseLocationinEditField_base.ValueChangingFcn = createCallbackFcn(app, @ResponseLocationinEditField_baseValueChanging, true);
            app.ResponseLocationinEditField_base.Value = '0';
            app.BeamLengthinEditFieldLabel = uilabel(app.BaseExcitationTab);
            app.BeamLengthinEditFieldLabel.Text = {'Beam '; 'Length (in)'};
            app.BeamLengthinEditField = uieditfield(app.BaseExcitationTab, 'numeric');
            app.BeamLengthinEditField.Editable = 'off';
            app.Rainflow = uibutton(app.BaseExcitationTab, 'push');
            app.Rainflow.ButtonPushedFcn = createCallbackFcn(app, @RainflowPushed, true);
            app.Rainflow.Text = 'Rainflow Fatigue';
            app.AppliedPointForceTab = uitab(app.TabGroup);
            app.AppliedPointForceTab.Title = 'Applied Point Force';
            app.AppliedPointForceTab.ButtonDownFcn = createCallbackFcn(app, @AppliedPointForceTabButtonDown, true);
            app.NumberofModesListBox_5Label = uilabel(app.AppliedPointForceTab);
            app.NumberofModesListBox_5Label.Text = {'Number of '; 'Modes'};
            app.NumberofModesListBox_pforce = uilistbox(app.AppliedPointForceTab);
            app.NumberofModesListBox_pforce.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_pforce.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBox_pforceValueChanged, true);
            app.NumberofModesListBox_pforce.Value = '1';
            app.TabGroup3 = uitabgroup(app.AppliedPointForceTab);
            app.TransferFunctionTab = uitab(app.TabGroup3);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.CalculateAppliedForce = uibutton(app.TransferFunctionTab, 'push');
            app.CalculateAppliedForce.ButtonPushedFcn = createCallbackFcn(app, @CalculateAppliedForceButtonPushed, true);
            app.CalculateAppliedForce.Text = 'Calculate';
            app.StartFrequencyHzEditField_2Label = uilabel(app.TransferFunctionTab);
            app.StartFrequencyHzEditField_2Label.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField_2 = uieditfield(app.TransferFunctionTab, 'numeric');
            app.StartFrequencyHzEditField_2.Value = 10;
            app.EndFrequencyHzEditField_2Label = uilabel(app.TransferFunctionTab);
            app.EndFrequencyHzEditField_2Label.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField_2 = uieditfield(app.TransferFunctionTab, 'numeric');
            app.EndFrequencyHzEditField_2.Value = 2000;
            app.SineTimeHistoryTab = uitab(app.TabGroup3);
            app.SineTimeHistoryTab.Title = 'Sine Time History';
            app.ForcelbfEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.ForcelbfEditFieldLabel.Text = 'Force (lbf)';
            app.ForcelbfEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.DurationsecEditField_2Label = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_force_sine = uieditfield(app.SineTimeHistoryTab, 'text');
            app.CalculateButton_force_sine = uibutton(app.SineTimeHistoryTab, 'push');
            app.CalculateButton_force_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_force_sinePushed, true);
            app.CalculateButton_force_sine.Text = 'Calculate';
            app.DurationsecEditField_2Label_2 = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label_2.Text = 'Freq (Hz)';
            app.FreqEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.SetfnButton = uibutton(app.SineTimeHistoryTab, 'push');
            app.SetfnButton.ButtonPushedFcn = createCallbackFcn(app, @SetfnButtonPushed, true);
            app.SetfnButton.Text = 'Set to Fundamental fn';
            app.DurationsecEditField_2Label_3 = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditField_2Label_3.Text = 'Sample Rate (Hz)';
            app.SampleRateEditField_force = uieditfield(app.SineTimeHistoryTab, 'text');
            app.ArbitraryTimeHistoryTab = uitab(app.TabGroup3);
            app.ArbitraryTimeHistoryTab.Title = 'Arbitrary Time History';
            app.CalculateAppliedForce_2 = uibutton(app.ArbitraryTimeHistoryTab, 'push');
            app.CalculateAppliedForce_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateAppliedForce_2ButtonPushed, true);
            app.CalculateAppliedForce_2.Text = 'Calculate';
            app.listbox_method_arb_force = uilistbox(app.ArbitraryTimeHistoryTab);
            app.listbox_method_arb_force.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_arb_force.ValueChangedFcn = createCallbackFcn(app, @listbox_method_arb_forceValueChanged, true);
            app.listbox_method_arb_force.Tag = 'listbox_method';
            app.listbox_method_arb_force.Value = 'Array Preloaded in Matlab';
            app.text1_3 = uilabel(app.ArbitraryTimeHistoryTab);
            app.text1_3.Tag = 'text1';
            app.text1_3.Text = 'Select Data Input Method';
            app.SelectInputArrayListBox_5Label = uilabel(app.ArbitraryTimeHistoryTab);
            app.SelectInputArrayListBox_5Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_arb_force = uilistbox(app.ArbitraryTimeHistoryTab);
            app.SelectInputArrayListBox_arb_force.Items = {'Item 1'};
            app.TheinputarraymusthavetwocolumnsLabel = uilabel(app.ArbitraryTimeHistoryTab);
            app.TheinputarraymusthavetwocolumnsLabel.Text = {' The input array must have '; ' two columns:  '};
            app.TimesecForcelbfLabel = uilabel(app.ArbitraryTimeHistoryTab);
            app.TimesecForcelbfLabel.Text = '   Time (sec) & Force (lbf)';
            app.UpdateListButton_arb_force = uibutton(app.ArbitraryTimeHistoryTab, 'push');
            app.UpdateListButton_arb_force.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_arb_forcePushed, true);
            app.UpdateListButton_arb_force.Text = 'Update List';
            app.ExportTab = uitab(app.TabGroup3);
            app.ExportTab.Title = 'Export';
            app.SaveTransButton_3 = uibutton(app.ExportTab, 'push');
            app.SaveTransButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveTransButton_3Pushed, true);
            app.SaveTransButton_3.Text = 'Save';
            app.Rainflow_2 = uibutton(app.ExportTab, 'push');
            app.Rainflow_2.ButtonPushedFcn = createCallbackFcn(app, @Rainflow_2ButtonPushed, true);
            app.Rainflow_2.Text = 'Rainflow Fatigue';
            app.OutputFunctionListBox_5Label = uilabel(app.ExportTab);
            app.OutputFunctionListBox_5Label.Text = 'Output Function';
            app.OutputFunctionListBox_force = uilistbox(app.ExportTab);
            app.OutputFunctionListBox_force.Items = {'Acceleration', 'Velocity', 'Displacement', 'Bending Stress'};
            app.OutputFunctionListBox_force.Value = 'Acceleration';
            app.OutputArrayNameEditField_5Label = uilabel(app.ExportTab);
            app.OutputArrayNameEditField_5Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_force = uieditfield(app.ExportTab, 'text');
            app.ResultsTextArea_forceLabel = uilabel(app.AppliedPointForceTab);
            app.ResultsTextArea_forceLabel.Text = 'Results';
            app.ResultsTextArea_force = uitextarea(app.AppliedPointForceTab);
            app.Image2 = uiimage(app.AppliedPointForceTab);
            app.AppliedDistributedForceTab = uitab(app.TabGroup);
            app.AppliedDistributedForceTab.Title = 'Applied Distributed Force';
            app.NumberofModesListBox_5Label_2 = uilabel(app.AppliedDistributedForceTab);
            app.NumberofModesListBox_5Label_2.Text = {'Number of '; 'Modes'};
            app.NumberofModesListBox_dforce = uilistbox(app.AppliedDistributedForceTab);
            app.NumberofModesListBox_dforce.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_dforce.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBox_dforceValueChanged, true);
            app.NumberofModesListBox_dforce.Value = '1';
            app.ResponseLocationinEditField_dforce_Label = uilabel(app.AppliedDistributedForceTab);
            app.ResponseLocationinEditField_dforce_Label.Text = {'Response '; 'Location (in)'};
            app.ResponseLocationinEditField_dforce = uieditfield(app.AppliedDistributedForceTab, 'text');
            app.ResponseLocationinEditField_dforce.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationinEditField_dforceValueChanged, true);
            app.ResponseLocationinEditField_dforce.Value = '0';
            app.BeamLengthinEditField_dforce_Label = uilabel(app.AppliedDistributedForceTab);
            app.BeamLengthinEditField_dforce_Label.Text = {'Beam '; 'Length (in)'};
            app.BeamLengthinEditField_dforce = uieditfield(app.AppliedDistributedForceTab, 'numeric');
            app.BeamLengthinEditField_dforce.ValueChangedFcn = createCallbackFcn(app, @BeamLengthinEditField_dforceValueChanged, true);
            app.BeamLengthinEditField_dforce.Editable = 'off';
            app.TabGroup2_2 = uitabgroup(app.AppliedDistributedForceTab);
            app.TransTab_2 = uitab(app.TabGroup2_2);
            app.TransTab_2.Title = 'Trans';
            app.CalculateBaseInputTrans_dforce = uibutton(app.TransTab_2, 'push');
            app.CalculateBaseInputTrans_dforce.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTrans_dforceButtonPushed2, true);
            app.CalculateBaseInputTrans_dforce.Text = 'Calculate';
            app.StartFrequencyHzEditField_3Label = uilabel(app.TransTab_2);
            app.StartFrequencyHzEditField_3Label.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField_dforce = uieditfield(app.TransTab_2, 'numeric');
            app.StartFrequencyHzEditField_dforce.ValueChangedFcn = createCallbackFcn(app, @StartFrequencyHzEditField_dforceValueChanged, true);
            app.StartFrequencyHzEditField_dforce.Value = 10;
            app.EndFrequencyHzEditField_3Label = uilabel(app.TransTab_2);
            app.EndFrequencyHzEditField_3Label.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField_dforce = uieditfield(app.TransTab_2, 'numeric');
            app.EndFrequencyHzEditField_dforce.ValueChangedFcn = createCallbackFcn(app, @EndFrequencyHzEditField_dforceValueChanged, true);
            app.EndFrequencyHzEditField_dforce.Value = 2000;
            app.PressurePSDTab = uitab(app.TabGroup2_2);
            app.PressurePSDTab.Title = 'Pressure PSD';
            app.Pressurepsi2HzLabel = uilabel(app.PressurePSDTab);
            app.Pressurepsi2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Pressure (psi^2/Hz)'; ''};
            app.listbox_method_psd_dforce = uilistbox(app.PressurePSDTab);
            app.listbox_method_psd_dforce.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd_dforce.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psd_dforceValueChanged, true);
            app.listbox_method_psd_dforce.Tag = 'listbox_method';
            app.listbox_method_psd_dforce.Value = 'Array Preloaded in Matlab';
            app.text1_psd_3 = uilabel(app.PressurePSDTab);
            app.text1_psd_3.Tag = 'text1';
            app.text1_psd_3.Text = 'Select Data Input Method';
            app.UpdateListButton_psd_dforce = uibutton(app.PressurePSDTab, 'push');
            app.UpdateListButton_psd_dforce.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psd_dforcePushed, true);
            app.UpdateListButton_psd_dforce.Text = 'Update List';
            app.SelectInputArrayListBox_2Label_5 = uilabel(app.PressurePSDTab);
            app.SelectInputArrayListBox_2Label_5.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd_dforce = uilistbox(app.PressurePSDTab);
            app.SelectInputArrayListBox_psd_dforce.Items = {'Item 1'};
            app.SelectInputArrayListBox_psd_dforce.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_psd_dforceValueChanged, true);
            app.Calculate_psd_dforce = uibutton(app.PressurePSDTab, 'push');
            app.Calculate_psd_dforce.ButtonPushedFcn = createCallbackFcn(app, @Calculate_psd_dforcePushed, true);
            app.Calculate_psd_dforce.Text = 'Calculate';
            app.ResultsTextArea_dforce_Label = uilabel(app.AppliedDistributedForceTab);
            app.ResultsTextArea_dforce_Label.Text = 'Results';
            app.ResultsTextArea_dforce = uitextarea(app.AppliedDistributedForceTab);
            app.SaveTransButton_dforce = uibutton(app.AppliedDistributedForceTab, 'push');
            app.SaveTransButton_dforce.ButtonPushedFcn = createCallbackFcn(app, @SaveTransButton_dforcePushed, true);
            app.SaveTransButton_dforce.Text = 'Save';
            app.OutputFunctionListBox_dforce_Label = uilabel(app.AppliedDistributedForceTab);
            app.OutputFunctionListBox_dforce_Label.Text = 'Output Function';
            app.OutputFunctionListBox_dforce = uilistbox(app.AppliedDistributedForceTab);
            app.OutputFunctionListBox_dforce.Items = {'Relative Displacement', 'Relative Velocity', 'Absolute Acceleration', 'Bending Moment', 'Bending Stress', 'Shear Force'};
            app.OutputFunctionListBox_dforce.Value = 'Relative Displacement';
            app.OutputArrayNameEditField_dforce_Label = uilabel(app.AppliedDistributedForceTab);
            app.OutputArrayNameEditField_dforce_Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_dforce = uieditfield(app.AppliedDistributedForceTab, 'text');
            app.ReturnButton = uibutton(app.BeamBendingContinuousModelUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.beam_bending_aver10byTomIrvineLabel = uilabel(app.BeamBendingContinuousModelUIFigure);
            app.beam_bending_aver10byTomIrvineLabel.Text = 'beam_bending_a    ver 2.3  by Tom Irvine      This script calculates the natural frequencies of a beam and its response to excitation via a continuous model classical solution.';
            % Show the figure after all components are created
            app.BeamBendingContinuousModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beam_bending_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BeamBendingContinuousModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BeamBendingContinuousModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamBendingContinuousModelUIFigure)
        end
    end
end