classdef beam_simply_supported_point_mass_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISimplySupportedBeamwithPointMassUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        LengthinEditFieldLabel          matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        CrossSectionListBoxLabel        matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        EditField_1Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        ImageIBeam                      matlab.ui.control.Image
        LeftBCListBoxLabel              matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        PointMasslbmEditFieldLabel      matlab.ui.control.Label
        PointMasslbmEditField           matlab.ui.control.NumericEditField
        AddPointMassListBoxLabel        matlab.ui.control.Label
        AddPointMassListBox             matlab.ui.control.ListBox
        DistancefromLeftEndinEditFieldLabel  matlab.ui.control.Label
        DistancefromLeftEndinEditField  matlab.ui.control.NumericEditField
        MaterialTab                     matlab.ui.container.Tab
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.NumericEditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.NumericEditField
        MaterialLabel                   matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        NaturalFrequenciesTab           matlab.ui.container.Tab
        CalculateNaturalFrequencyButton  matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        DampingTypeListBoxLabel         matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        ModalDistributionListBox        matlab.ui.control.ListBox
        UITable                         matlab.ui.control.Table
        SaveDampingButton               matlab.ui.control.Button
        BaseInputTransTab               matlab.ui.container.Tab
        CalculateBaseInputTrans         matlab.ui.control.Button
        ResponseLocationinEditFieldLabel  matlab.ui.control.Label
        ResponseLocationinEditField     matlab.ui.control.NumericEditField
        StartFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        EndFrequencyHzEditFieldLabel    matlab.ui.control.Label
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        NumberofModesListBoxLabel       matlab.ui.control.Label
        NumberofModesListBox            matlab.ui.control.ListBox
        LengthinEditField_2Label        matlab.ui.control.Label
        LengthinEditField_trans         matlab.ui.control.NumericEditField
        SaveTransButton                 matlab.ui.control.Button
        OutputFunctionListBoxLabel      matlab.ui.control.Label
        OutputFunctionListBox           matlab.ui.control.ListBox
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        ImageBeamBase                   matlab.ui.control.Image
        BaseInputSineTab                matlab.ui.container.Tab
        CalculateBaseInputSine          matlab.ui.control.Button
        ResponseLocationinLabel_2       matlab.ui.control.Label
        ResponseLocationEditField_sine  matlab.ui.control.NumericEditField
        ExcitationFrequencyHzLabel      matlab.ui.control.Label
        ExcitationFrequencyHzEditField_sine  matlab.ui.control.NumericEditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        BaseAccelerationGEditField      matlab.ui.control.NumericEditField
        NumberofModesListBox_2Label     matlab.ui.control.Label
        NumberofModesListBox_sine       matlab.ui.control.ListBox
        LengthinEditField_2Label_2      matlab.ui.control.Label
        LengthinEditField_sine          matlab.ui.control.NumericEditField
        SettoFirstfnButton              matlab.ui.control.Button
        ImageBeamBase_sine              matlab.ui.control.Image
        BaseInputHalfSineTab            matlab.ui.container.Tab
        CalculateBaseInput_HS           matlab.ui.control.Button
        ImageBeamBase_HS                matlab.ui.control.Image
        NumberofModesListBox_2Label_2   matlab.ui.control.Label
        NumberofModesListBox_HS         matlab.ui.control.ListBox
        ResponseLocationinLabel_4       matlab.ui.control.Label
        ResponseLocationEditField_HS    matlab.ui.control.NumericEditField
        LengthinEditField_2Label_5      matlab.ui.control.Label
        LengthinEditField_HS            matlab.ui.control.NumericEditField
        ResponsetoHalfSineBaseInputLabel  matlab.ui.control.Label
        BaseInputAccelGEditFieldLabel   matlab.ui.control.Label
        BaseInputAccelGEditField        matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.NumericEditField
        SaveButton_HS                   matlab.ui.control.Button
        OutputFunctionListBox_2Label_3  matlab.ui.control.Label
        OutputFunctionListBox_HS        matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label_3  matlab.ui.control.Label
        OutputArrayNameEditField_HS     matlab.ui.control.EditField
        BaseInputPSDTab                 matlab.ui.container.Tab
        CalculateBaseInputPSD           matlab.ui.control.Button
        ImageBeamBase_PSD               matlab.ui.control.Image
        NumberofModesListBox_2Label_3   matlab.ui.control.Label
        NumberofModesListBox_PSD        matlab.ui.control.ListBox
        ResponseLocationinLabel         matlab.ui.control.Label
        ResponseLocationEditField_PSD   matlab.ui.control.NumericEditField
        LengthinEditField_2Label_4      matlab.ui.control.Label
        LengthinEditField_PSD           matlab.ui.control.NumericEditField
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        InputArrayNameEditField_2Label  matlab.ui.control.Label
        InputArrayNameEditField_PSD     matlab.ui.control.EditField
        SaveButton_PSD                  matlab.ui.control.Button
        OutputFunctionListBox_2Label_2  matlab.ui.control.Label
        OutputFunctionListBox_PSD       matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        BaseInputArbitraryTab           matlab.ui.container.Tab
        CalculateBaseInputArbitrary     matlab.ui.control.Button
        ResponseLocationinLabel_3       matlab.ui.control.Label
        ResponseLocationEditField_arbitrary  matlab.ui.control.NumericEditField
        NumberofModesListBox_3Label     matlab.ui.control.Label
        NumberofModesListBox_arbitrary  matlab.ui.control.ListBox
        LengthinEditField_2Label_3      matlab.ui.control.Label
        LengthinEditField_arbitrary     matlab.ui.control.NumericEditField
        TheinputarraymusthavetwocolumnstimesecaccelGLabel  matlab.ui.control.Label
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        ImageBeamBase_arbitrary         matlab.ui.control.Image
        SaveButton_arbitrary            matlab.ui.control.Button
        OutputFunctionListBox_2Label    matlab.ui.control.Label
        OutputFunctionListBox_arbitrary  matlab.ui.control.ListBox
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        OutputArrayNameEditField_arbitrary  matlab.ui.control.EditField
        ReturnButton                    matlab.ui.control.Button
        beam_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabsLocationButtonGroup         matlab.ui.container.ButtonGroup
        TopButton                       matlab.ui.control.ToggleButton
        RightButton                     matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        L   % Length
        E   % Face Sheet elastic modulus
        rho % Face Sheet mass density
        fn  % Natural frequencies
        dm  % Damping
        omegan % Angular natural frequencies
        ModeShapes % ModeShapes
        cna % Distance to neutral axis
        x   % x length along beam for mode shapes
        y   % Mode shape
        xx   % x length along beam for mode shapes
        ZZ   % Mode shape
        sq_mass % sqrt of mass
        MOI % Area moment of intertia
        C   % Modal constant
        beta % eigenvalue parameter
        part % participation factor
        s1  % modal frequency 1
        s2  % modal frequency 2
        s3  % modal frequency 3
        s4  % modal frequency 4
        s5  % modal frequency 5
        s6  % modal frequency 6
        RBC % Right boundary condition
        LBC % Left boundary condition
        bending_stress % 
        shear_force    % 
        bending_moment % 
        absolute_accel % 
        relative_vel   % 
        relative_disp  %   
        acceleration   %
        relative_velocity     %
        relative_displacement %   
        acc_response_psd %
	rv_response_psd  %
        rd_response_psd  %
        moment_response_psd %            
        shear_response_psd  %
        stress_response_psd %
    end
    
    methods (Access = private)
        
        function CloseFigures(app)
             try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
        
        function ChangeMaterials(app)
            value = app.MaterialListBox.Value;
            
            app.E=0;
            app.rho=0;            
            
            if(contains(value,'Aluminum'))
                app.E=1e+007;
                app.rho=0.1;                  
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Steel'))
                app.E=3e+007;
                app.rho= 0.28;               
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Copper'))
                app.E=1.6e+007;
                app.rho=  0.322;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'G10'))
                app.E=2.7e+006;
                app.rho=  0.065;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Titanium'))
                app.E=1.6e+07;           
                app.rho=0.163;                
            end
             value = app.MaterialListBox.Value;
            if(contains(value,'Graphite/Epoxy'))
                app.E=1e+007;
                app.rho=0.058;                           
            end        
            app.ElasticModuluspsiEditField.Value=app.E;
            app.MassDensitylbmin3EditField.Value=app.rho;        
            
            app.SaveTransButton.Enable='off';
            app.SaveButton_arbitrary.Enable='off';
            app.SaveButton_PSD.Enable='off';
            app.SaveButton_HS.Enable='off';
        end
        
        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end
        
        function ChangeCrossSection(app)
            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                app.EditField_1Label.Text='Width (in)';
                app.EditField_2Label.Text='Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                
            end
            if(contains(n,'Pipe'))
                app.EditField_1Label.Text='Outer Diameter (in)';
                app.EditField_2Label.Text='Wall Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 app.EditField_1Label.Text='Diameter (in)';
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                app.EditField_1Label.Text='Base Width (in)';
                app.EditField_2Label.Text='Total Height (in)';
                app.EditField_3Label.Text='Flange Thickness (in)';     
                app.EditField_4Label.Text='Web Thickness (in)';  
                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                app.EditField_1Label.Text='Area (in^2)';
                app.EditField_2Label.Text='Area moment Inertia (in^4)';
                app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end

            
        end
        
        function BeamBaseImage(app)
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;  
            
            app.ImageBeamBase.Visible='off';
            app.ImageBeamBase_sine.Visible='off';
            app.ImageBeamBase_arbitrary.Visible='off';   
            app.ImageBeamBase_HS.Visible='off'; 
            app.ImageBeamBase_PSD.Visible='off'; 
   
            
            if(contains(nL,'Fixed') && contains(nR,'Free'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on'; 
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on'; 
                                 
                app.ImageBeamBase.ImageSource='beam_bending_base.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base.jpg'; 
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base.jpg';
                app.ImageBeamBase_MDOFSRS.ImageSource='beam_bending_base.jpg';
            end
            if(contains(nL,'Pinned') && contains(nR,'Pinned'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on'; 
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on';              
                app.ImageBeamBase.ImageSource='beam_bending_base_ss.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base_ss.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base_ss.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base_ss.jpg';
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base_ss.jpg';              
            end   
            if(contains(nL,'Fixed') && contains(nR,'Fixed'))
                app.ImageBeamBase.Visible='on';
                app.ImageBeamBase_sine.Visible='on';
                app.ImageBeamBase_arbitrary.Visible='on';
                app.ImageBeamBase_HS.Visible='on'; 
                app.ImageBeamBase_PSD.Visible='on'; 
                                
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';     
                app.ImageBeamBase_sine.ImageSource='beam_bending_base_fixed.jpg';   
                app.ImageBeamBase_arbitrary.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_HS.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_PSD.ImageSource='beam_bending_base_fixed.jpg';
                app.ImageBeamBase_MDOFSRS.ImageSource='beam_bending_base_fixed.jpg';             
            end
            
        end
        
        function APM(app)
            value = strtrim(app.AddPointMassListBox.Value);
            if(strcmp(value,'Yes'))
                app.PointMasslbmEditField.Visible='on';
                app.PointMasslbmEditFieldLabel.Visible='on';
                app.DistancefromLeftEndinEditField.Visible='on';
                app.DistancefromLeftEndinEditFieldLabel.Visible='on';
            else  
                app.PointMasslbmEditField.Visible='off';
                app.PointMasslbmEditFieldLabel.Visible='off';   
                app.DistancefromLeftEndinEditField.Visible='off';
                app.DistancefromLeftEndinEditFieldLabel.Visible='off';                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            APM(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            ChangeCrossSection(app);
            BeamBaseImage(app);
            ss=sprintf('Response Location\nDistance from    \nLeft Boundary (in)  ');
            app.ResponseLocationinEditFieldLabel.Text=ss;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)

            ChangeMaterials(app);
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed(app, event)
       
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end      
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            iu=1;
            
            app.xx=app.ResponseLocationinEditField.Value;  
            
            nu=app.NumberofModesListBox.Value;
            
            number_modes=round(str2double(nu));
 
            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
               
            N=48;
            fmin=fstart;
            [f,~,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            ffn=app.fn(1:number_modes);
            ddm=app.dm(1:number_modes);
            ppart=app.part(1:number_modes);
               
            
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,iu,app.LBC,app.RBC,f,ppart,ffn,ddm,app.E,app.MOI);   
                   
                
            stress_trans=moment_trans;
               
            fprintf('  cna=%8.4g  I=%8.4g  cna/I=%8.4g \n',app.cna,app.MOI,app.cna/app.MOI);
               
            stress_trans(:,2)=stress_trans(:,2)*app.cna/app.MOI;
                
            [maxF,maxH]=find_max_alt(rd_trans);
            [maxFV,maxHV]=find_max_alt(rv_trans);    
            [maxFA,maxHA]=find_max_alt(acc_trans);  
            [maxFBM,maxHBM]=find_max_alt(moment_trans);
            [maxFSH,maxHSH]=find_max_alt(shear_trans);    
            [maxFST,maxHST]=find_max_alt(stress_trans);      
            
            md=6;
            x_label='Frequency (Hz)';
                
            fprintf('\n Response Location: x=%g inches\n\n',app.xx);
       
            fmin=fstart;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iu==1)
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g inch',app.xx);
                y_label='Rel Disp (in)/ Base Accel (G)';
            else
                t_string=sprintf('Relative displacement Frequency Response Function  x=%g m',app.xx);
                y_label='Rel Disp (m)/ Base Accel (G)';        
            end
          
            try
                ppp=rd_trans;
                if(min(ppp(:,2))>1.e-90)
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                end    
            catch
            end
           
            disp(' ');
            if(iu==1)
                fprintf('  max Rel Disp FRF = %9.5f (in/G) at %8.4g Hz \n',maxH,maxF);
            else
                fprintf('  max Rel Disp FRF = %9.5f (m/G) at %8.4g Hz \n',maxH,maxF);       
            end
            %
            app.relative_disp=rd_trans;
            

                if(iu==1)
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g inch',app.xx);
                    y_label='Rel Vel (in/sec)/ Base Accel (G)';
                else
                    t_string=sprintf('Relative Velocity Frequency Response Function  x=%g m',app.xx);
                    y_label='Rel Vel (m/sec)/ Base Accel (G)';        
                end
                
                try
                    ppp=rv_trans;
                    if(min(ppp(:,2))>1.e-90)
                        [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    end
                catch
                end
            %
                disp(' ');
                try
                
                    if(iu==1)
                        fprintf('  max Rel Vel FRF = %9.5f ((in/sec)/G) at %8.4g Hz \n',maxHV,maxFV);
                    else
                        fprintf('  max Rel Vel FRF = %9.5f ((m/sec)/G) at %8.4g Hz \n',maxHV,maxFV);       
                    end
                catch 
                end
            %
                app.relative_vel=rv_trans;
            
                if(iu==1)
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g inch',app.xx);
                else
                    t_string=sprintf('Acceleration Frequency Response Function  x=%g m',app.xx);        
                end
                y_label='Absolute Accel / Base Accel';    
                
                ppp=acc_trans; 
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                    
                fprintf('\n  max Absolute Accel FRF = %8.4g (G/G) at %8.4g Hz \n',maxHA,maxFA);
            %
                app.absolute_accel=acc_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(iu==1)
                    t_string=sprintf('Bending moment Frequency Response Function  x=%g inch',app.xx);
                    y_label='moment (lbf-in)/ Base Accel (G)';
                else
                    t_string=sprintf('Bending moment Frequency Response Function  x=%g m',app.xx);
                    y_label='moment (N-m)/ Base Accel (G)';        
                end
            %
                ppp=moment_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(iu==1)
                    fprintf('  max Bending moment FRF = %9.5g (lbf-in/G) at %8.4g Hz \n',maxHBM,maxFBM);
                else
                    fprintf('  max Bending moment FRF = %9.5g (N-m/G) at %8.4g Hz \n',maxHBM,maxFBM);       
                end
            %
                app.bending_moment=moment_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                if(iu==1)
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g inch',app.xx);
                    y_label='Shear (lbf)/ Base Accel (G)';
                else
                    t_string=sprintf('Shear Force Frequency Response Function  x=%g m',app.xx);
                    y_label='Shear (N)/ Base Accel (G)';        
                end
            %
                ppp=shear_trans;
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
            %
                if(iu==1)
                    fprintf('  max Shear Force FRF = %9.5g (lbf/G) at %8.4g Hz \n',maxHSH,maxFSH);
                else
                    fprintf('  max Shear Force FRF = %9.5g (N/G) at %8.4g Hz \n',maxHSH,maxFSH);       
                end
            %
                app.shear_force=shear_trans;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                ppp=stress_trans;
            
                if(iu==1)
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g inch',app.xx);
                    y_label='Stress (psi) / Base Accel (G)';
                else
                    t_string=sprintf('Bending Stress Frequency Response Function  x=%g m',app.xx);
                    y_label='Stress (Pa) / Base Accel (G)';        
                end
            
                try
                    [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                catch
                end
                    
                if(iu==1)
                    fprintf('  max Bending Stress FRF = %9.5g (psi/G) at %8.4g Hz \n',maxHST,maxFST);
                else
                    fprintf('  max Bending Stress FRF = %9.5g (Pa/G) at %8.4g Hz \n',maxHST,maxFST);       
                end
            %
                app.bending_stress=stress_trans;
                
                app.SaveTransButton.Enable='on';
            
        end

        % Button pushed function: CalculateBaseInputSine
        function CalculateBaseInputSineButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end   
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            iu=1;
            
            app.xx=app.ResponseLocationEditField_sine.Value;  
            
            nu=app.NumberofModesListBox.Value;        
            number_modes=round(str2double(nu));
                  
            f_base=app.ExcitationFrequencyHzEditField_sine.Value;
            amp=app.BaseAccelerationGEditField.Value;

            EI=app.E*app.MOI;

            n=number_modes;
              
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);      

            %
            arg=app.beta*app.xx;
            %
            sH=0;
            sHv=0;
            sH_relA=0;
            om=2*pi*f_base;
            %
        
            xxu=sort(unique([app.xx app.L*[0 0.5 1]]));
            
            nxx=length(xxu);
               
            bm=zeros(nxx,1);
            shear=zeros(nxx,1);
            %
            for i=1:n
            %
                pY=app.part(i)*ModeShape(arg(i),app.C(i),app.sq_mass);
                omn=2*pi*app.fn(i);
                num=-pY;
                den=(omn^2-om^2)+(1i)*2*app.dm(i)*om*omn;
                nrd=num/den;
                sH=sH+nrd;
            %
                sHv=sHv+(1i)*om*(num/den);
            %
                num=om^2*pY;
                sH_relA=sH_relA+(num/den); 
            %
                for k=1:nxx                      
                       alpha=app.beta(i)*xxu(k);                          
                       pY=app.part(i)*ModeShape_dd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       bm(k)= bm(k) +(num/den);     
                       
                       pY=app.part(i)*ModeShape_ddd(alpha,app.C(i),app.beta(i),app.sq_mass);
                       num=-pY;
                       shear(k)= shear(k) +(num/den);                
                end
            %
            end
            
            bm=abs(bm);
            shear=abs(shear);
            %
            bm=EI*bm;
            shear=EI*shear;
            %
            sH=abs(sH);
            sHv=abs(sHv);
            sHA=abs(sH_relA+1);      
        
            %
            fprintf('  Base excitation = %8.4g G at %8.4g Hz \n',amp,f_base);
            %
            disp(' ');
            if(iu==1)
                fprintf('     Response at x=%8.4g inch \n',app.xx);
            else
                fprintf('     Response at x=%8.4g m \n\n',app.xx);    
            end
            %
            fprintf(' Transfer Magnitude = %8.4g G/G \n',sHA);
            fprintf('              Accel = %8.4g G \n',sHA*amp);
            %
        
            if(iu==1)
                conv=386;
            else
                conv=9.81;    
            end
        
            sH=sH*conv;
            sHv=sHv*conv;
            
            bm=bm*conv;
            shear=shear*conv;
        
            if(iu==1)
                fprintf('            Rel Vel = %8.4g in/sec \n',sHv*amp);
            else
                fprintf('            Rel Vel = %8.4g m/sec \n',sHv*amp);    
            end
            
            if(iu==1)
                fprintf('           Rel Disp = %8.4g in \n\n',sH*amp);
            else
                fprintf('           Rel Disp = %8.4g m \n\n',sH*amp);    
            end

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            c=app.cna;
            bm=bm*amp;
            shear=shear*amp;
            %
            if(iu==1)
                
                out2=sprintf(' Distance from neutral axis = %8.4g in ',app.cna);
                
                out3=sprintf('\n Bending moment = %8.4g in-lbf at %6.3g in',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g in-lbf at %6.3g in',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g in-lbf at %6.3g in',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g in-lbf at %6.3g in',bm(4),xxu(4));               
                  end        
                
                
                out13=sprintf('\n Shear Force = %8.4g lbf at %6.3g in',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g lbf at %6.3g in',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g lbf at %6.3g in',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g lbf at %6.3g in',shear(4),xxu(4));               
                  end               
                  
                  
                out23=sprintf('\n Bending Stress = %8.4g psi at %6.3g in',(c/app.MOI)*bm(1),xxu(1)); 
                  out24=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(2),xxu(2));   
                  out25=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out26=sprintf('                = %8.4g psi at %6.3g in',(c/app.MOI)*bm(4),xxu(4));               
                  end
            
            else
                
                out2=sprintf('\n Distance from neutral axis = %8.4g mm ',app.cna*1000);
                
                out3=sprintf('\n Bending moment = %8.4g N-m at %6.3g m',bm(1),xxu(1)); 
                  out4=sprintf('                = %8.4g N-m at %6.3g m',bm(2),xxu(2));   
                  out5=sprintf('                = %8.4g N-m at %6.3g m',bm(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out6=sprintf('                = %8.4g N-m at %6.3g m',bm(4),xxu(4));               
                  end     
                  
                  
                 out13=sprintf('\n Shear Force = %8.4g N at %6.3g m',shear(1),xxu(1)); 
                  out14=sprintf('                = %8.4g N at %6.3g m',shear(2),xxu(2));   
                  out15=sprintf('                = %8.4g N at %6.3g m',shear(3),xxu(3));           
           
                  if(length(xxu)==4)
                    out16=sprintf('                = %8.4g N at %6.3g m',shear(4),xxu(4));               
                  end           
                   
                
                out23=sprintf('\n Bending Stress = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  out24=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));   
                  out25=sprintf('                = %8.4g Pa at %6.3g m',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));
            
                   if(length(xxu)==4)
                    out26=sprintf('                = %8.4g Pa at %6.3g m',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    
        %
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            if(length(xxu)==4)
                disp(out6);
            end    
            
        
            disp(out13);
            disp(out14);
            disp(out15);
            if(length(xxu)==4)
                disp(out16);
            end    
        
            disp(out23);
            disp(out24);
            disp(out25);
            if(length(xxu)==4)
                disp(out26);
            end    
            disp(' ');       
                        
            msgbox('Calculation complete.  Output written to Matlab Command Window.');

        end

        % Button pushed function: CalculateBaseInput_HS
        function CalculateBaseInput_HSButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end
            
            A=app.BaseInputAccelGEditField.Value;
            T=app.DurationsecEditField.Value;
            
            disp('  ');
            disp(' * * * * * ');
            disp(' ');           

            fig_num=1;
   
            app.xx=app.ResponseLocationEditField_HS.Value;  

            num_fn=length(app.fn);
            
            nmn=min([str2num(app.NumberofModesListBox_HS.Value) num_fn]);
            ffn=app.fn(1:nmn);        
     
            PF=app.part;
            
            iu=1;       

            c=app.cna;    

            EI=app.E*app.MOI;
                    
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);
            %
            dur1=5*T;
            dur2=5/ffn(1);
            dur=max([dur1 dur2]);
            %
            dt1=T/20;
            dt2=(1/max(ffn))/10;
            %
            dt=min([dt1 dt2]);
            %
            num_steps=round(dur/dt);
            %
            t=linspace(0,dur,num_steps);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            omega=pi/T;
            %
            den=zeros(nmn,1);
            U1=zeros(nmn,1);
            U2=zeros(nmn,1);
            V1=zeros(nmn,1);
            V2=zeros(nmn,1);
            P=zeros(nmn,1);
            omegad=zeros(nmn,1);
            domegan=zeros(nmn,1);
            omn2=zeros(nmn,1);
            %
            An=zeros(nmn,1);
            %
            A11=zeros(nmn,1);
            A12=zeros(nmn,1);
            A21=zeros(nmn,1);
            A22=zeros(nmn,1);
            %
            om2=omega^2;
            %    
            for i=1:nmn
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
                domegan(i)=app.dm(i)*app.omegan(i);
                omn2(i)=(app.omegan(i))^2;
                den(i)=( (om2-omn2(i))^2 + (2*app.dm(i)*omega*app.omegan(i))^2);
                U1(i)=2*app.dm(i)*omega*app.omegan(i);
                U2(i)=om2-omn2(i);
                V1(i)=2*app.dm(i)*app.omegan(i)*omegad(i);
                V2(i)=om2-omn2(i)*(1-2*(app.dm(i))^2);
                P(i)=omega/omegad(i);
            %
                A11(i)=-(om2-omn2(i));
                A12(i)=-2*app.dm(i)*omega*app.omegan(i);
                A21(i)=2*app.dm(i)*om2;
                A22(i)=(app.omegan(i)/omegad(i))*(-omn2(i)+om2*(1-2*app.dm(i)^2));
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Transition points
            %
            eee=exp(-domegan*T); 
            %
            arg=omega*T;
            argd=omegad*T;
            %
            c1=cos(arg);
            ss1=sin(arg);
            cd=cos(argd);
            sd=sin(argd);
            %
            W=zeros(nmn,1);
            Tn=zeros(nmn,1);
            Tnv=zeros(nmn,1);
            deee=zeros(nmn,1);
            %
            for j=1:nmn
            %
                An(j)=PF(j)*A;   
            %               
                term1=U1(j)*c1 + U2(j)*ss1;
                term2=V1(j)*cd(j)+V2(j)*sd(j);
                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
            %
                Tn(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                Tnv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
            %
                W(j)= ( Tnv(j) +   app.dm(j)*app.omegan(j)*Tn(j) )/omegad(j);
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                abase=zeros(num_steps,1);
                   rd=zeros(num_steps,1);
                  acc=zeros(num_steps,1);
                   bm=zeros(num_steps,1);
                   shear=zeros(num_steps,1);
            %
            for i=1:num_steps
            %
                    for j=1:nmn
                        eee(j)=exp(-domegan(j)*t(i)); 
                    end   
            %
                    arg=omega*t(i);
                    argd=omegad*t(i);
            %
                    c1=cos(arg);
                    ss1=sin(arg);
                    cd=cos(argd);
                    sd=sin(argd);     
            %
                    n=zeros(nmn,1);
                    nv=zeros(nmn,1);
                    na=zeros(nmn,1);
                    An=zeros(nmn,1);
            %
                    deee=zeros(nmn,1);
            %
                    for j=1:nmn
            %            
                        if(abs(PF(j))>0)
            %
                            if t(i)<=T
            %                   
                                abase(i)=A*ss1;
                                An(j)=PF(j)*A;
            %               
                                term1=U1(j)*c1 + U2(j)*ss1;
                                term2=V1(j)*cd(j)+V2(j)*sd(j);
                                dterm1=    omega*( -U1(j)*ss1 + U2(j)*c1 );
                                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                                deee(j)=-damp(j)*app.omegan(j)*eee(j);
            %
                                n(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                                nv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);
            %
                                a1=om2*(  A11(j)*ss1  + A12(j)*c1);
                                a2=omega*app.omegan(j)*eee(j)*(  A21(j)*cd(j) +A22(j)*sd(j));
            %                    
                                na(j)=An(j)*(a1+a2)/den(j);
            %
                            else
                                ts=t(i)-T;
            %
                                eee(j)=exp(-domegan(j)*ts); 
                                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);                
            %
                                arg=omega*ts;
            %
                                argd=omegad*ts;
            %
                                c1=cos(arg);
                                ss1=sin(arg);
                                cd=cos(argd);
                                sd=sin(argd);
            %
                                 n(j)=    eee(j)*( Tn(j)*cd(j)  +W(j)*sd(j) );
                                nv(j)=   deee(j)*n(j)...
                                        +omegad(j)*eee(j)*( -Tn(j)*sd(j)  +W(j)*cd(j) );
            %                        
                                na(j)= -2*app.dm(j)*app.omegan(j)*nv(j) -omn2(j)*n(j);      
                            end
            %
                            arg=app.beta(j)*app.xx;
                            app.ZZ=ModeShape(arg,app.C(j),app.sq_mass); 
                            ZZdd=ModeShape_dd(arg,app.C(j),app.beta(j),app.sq_mass);    
                            ZZddd=ModeShape_ddd(arg,app.C(j),app.beta(j),app.sq_mass);                   
            %
                            rd(i)=rd(i) +app.ZZ*n(j);
                           acc(i)=acc(i)+app.ZZ*na(j);
                            bm(i)=bm(i)+ZZdd*n(j);
                            shear(i)=shear(i)+ZZddd*n(j);                
            %                           
                        end  % PF                    
            %
                    end  % j mode
            %
            end      % i  time 
            %     
            acc=acc+abase;
            %
            if(iu==1)
                    rd=rd*386;
                    bm=bm*386;
                    shear=shear*386;        
            else
                    rd=rd*9.81;
                    bm=bm*9.81;   
                    shear=shear*9.81;        
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            bm=bm*EI;
            shear=shear*EI;
            %
               
            disp(' ');
            if(iu==1)
                fprintf(' Peak Responses at x=%g inch \n\n',app.xx);
            else
                fprintf(' Peak Responses at x=%g m  \n\n',app.xx);    
            end
  
            fprintf('          Acceleration = %8.4g G \n',max(abs(acc)));
            %   
            if(iu==1)
                    fprintf(' Relative Displacement = %8.4g in \n\n',max(abs(rd)));   
                    fprintf('        Bending Moment = %8.4g in-lbf \n',max(abs(bm)));  
                    fprintf('           Shear Force = %8.4g lbf \n',max(abs(shear)));        
                    fprintf('        Bending Stress = %8.4g psi \n',(c/app.MOI)*max(abs(bm)));  
            else
                    fprintf(' Relative Displacement = %8.4g m \n\n',max(abs(rd))); 
                    fprintf('        Bending Moment = %8.4g N-m \n',max(abs(bm))); 
                    fprintf('           Shear Force = %8.4g N \n',max(abs(shear)));         
                    fprintf('        Bending Stress = %8.4g Pa \n',(c/app.MOI)*max(abs(bm)));        
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                t=fix_size(t);
            %
                acc=fix_size(acc);
                rd=fix_size(rd);
            %%%
                app.absolute_accel=[t acc];
                app.relative_disp=[t rd];  
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %            
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,rd);
            %
            if(iu==1)
                out1=sprintf('Relative Displacement at %g in',app.xx);        
                ylabel('Rel Disp(in) ');
            else
                out1=sprintf('Relative Displacement at %g m',app.xx);        
                ylabel('Rel Disp(m) ');
            end
            title(out1);
            xlabel('Time(sec)');
            grid on;
            %
            %
            figure(fig_num)
            fig_num=fig_num+1;
            %
            plot(t,acc,t,abase);
            %
            if(iu==1)
                out1=sprintf('Acceleration at %g in',app.xx);
            else
                out1=sprintf('Acceleration at %g m',app.xx);        
            end
            %    
            title(out1);
            ylabel('Accel(G) ');
            xlabel('Time(sec)');
            legend('response','input');  
            grid on;

            setappdata(0,'fig_num',fig_num);    
   
            app.SaveButton_HS.Enable='on';
            
            msgbox('Calculation complete.  Output written to Matlab Command Window.');
                      
        end

        % Button pushed function: CalculateBaseInputPSD
        function CalculateBaseInputPSDButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end     
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            iu=1;
            
            fig_num=1;
            
            app.xx=app.ResponseLocationEditField_PSD.Value;  
            
            nu=app.NumberofModesListBox_PSD.Value;        
            number_modes=round(str2double(nu));
            
            FS = app.InputArrayNameEditField_PSD.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end            

            ffn=app.fn(1:number_modes);
            damp=app.dm(1:number_modes);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    acc_trans
            %

            fmin=THM(1,1);
            fmax=THM(end,1);

            disp(' ');
           
            fstart=0.8*THM(1,1);
            fend=THM(end,1);

            N=48;
            %
            noct=log(fend/fstart)/log(2);
            np=floor(noct*N);
            %
            freq=zeros(np,1);
            %
            R=2^(1/N);
            %
            freq(1)=fstart;
            for i=2:np
                freq(i)=freq(i-1)*R;
            end
            freq(np)=fend;           
                      
            [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                  beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,iu,app.LBC,app.RBC,freq,app.part,ffn,damp,app.E,app.MOI);
           
            PSD_in=THM;
            
            [app.acc_response_psd]=psd_mult_trans(PSD_in,acc_trans);
            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  );
            
            sz=size(app.acc_response_psd);
            
            fff=app.acc_response_psd(:,1);
            
            %%%%%%%%%%%%%%
        
            app.rv_response_psd=zeros(sz(1),sz(2));
            app.rv_response_psd(:,1)=fff;
            
            app.rd_response_psd=zeros(sz(1),sz(2));
            app.rd_response_psd(:,1)=fff;
            
            app.moment_response_psd=zeros(sz(1),sz(2));
            app.moment_response_psd(:,1)=fff;
            
            app.shear_response_psd=zeros(sz(1),sz(2));
            app.shear_response_psd(:,1)=fff;
            
            app.stress_response_psd=zeros(sz(1),sz(2));
            app.stress_response_psd(:,1)=fff;
        
            %%%%%%%%%%%%%%
            
            disp_rms=0.;
            velox_rms=0;
            bm_rms=0;
            shear_rms=0;
            stress_rms=0.;
            
            vflag=0;
            if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            %    
                vflag=1; 
                [app.rv_response_psd]=psd_mult_trans(PSD_in,rv_trans);
                [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psd(:,1),app.rv_response_psd(:,2)  );
            %
            end   
            
            rflag=0;
            if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
            %    
                rflag=1; 
                [app.rd_response_psd]=psd_mult_trans(PSD_in,rd_trans);
                [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );
            %
            end    
            
            shear_flag=0;
            if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
            %  
                shear_flag=1; 
                [app.shear_response_psd]=psd_mult_trans(PSD_in,shear_trans);
                
                [~,shear_rms]=calculate_PSD_slopes_no(  app.shear_response_psd(:,1),app.shear_response_psd(:,2)  );    
            %
            end
        
            sflag=0;
            if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            %  
                sflag=1; 
                [app.moment_response_psd]=psd_mult_trans(PSD_in,moment_trans);
                
                [~,bm_rms]=calculate_PSD_slopes_no(  app.moment_response_psd(:,1),app.moment_response_psd(:,2)  );    
                       
                app.stress_response_psd(:,1)=        app.moment_response_psd(:,1);    
                app.stress_response_psd(:,2)=(app.cna/app.MOI)^2*app.moment_response_psd(:,2);
                
                [~,stress_rms]=calculate_PSD_slopes_no(  app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 
            %
            end

            if(iu==1)
               su='psi';
               sv='in/sec';
               dd='in';
               sb='in-lbf';
               st='psi';
               sh='lbf';
               xu='in';
            else
               su='Pa';
               sv='m/sec';
               dd='mm';
               sb='N-m';
               st='Pa';
               sh='N';
               xu='m';
            %
               disp_rms=disp_rms*1000;
            %
            end

            fprintf('\n Response Overall Levels at x=%g %s \n',app.xx,xu);
            fprintf('\n  Acceleration = %8.4g GRMS \n',accel_rms);
    
            fprintf('  Relative Velocity = %8.4g %s RMS \n',velox_rms,sv);
            fprintf('  Relative displacement = %8.4g %s RMS \n\n',disp_rms,dd);
            fprintf('  Bending moment = %8.4g %s RMS \n',bm_rms,sb);
            fprintf('  Shear Force = %8.4g %s RMS \n',shear_rms,sh);
            fprintf('  Stress = %8.4g %s RMS \n',stress_rms,st);
         
            md=6;
            x_label='Frequency (Hz)';
    
            ylab_acc=sprintf('Accel (G^2/Hz)');
            %
            if(iu==1)
                ylab_bm=sprintf('Bending moment ((in-lbf)^2/Hz)');    
                ylab_stress=sprintf('Stress (psi^2/Hz)');
                ylab_rv=sprintf('Rel Vel ((in/sec^2)^2/Hz)');
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
                ylab_sh=sprintf('Shear (lbf^2/Hz)');   
            else
                ylab_bm=sprintf('Bending moment ((N-m)^2/Hz)');     
                ylab_stress=sprintf('Stress (Pa^2/Hz)'); 
                ylab_rv=sprintf('Rel Vel ((m/sec^2)^2/Hz)');    
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
                ylab_sh=sprintf('Shear (N^2/Hz)');      
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes_no( THM(:,1),THM(:,2) );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            ppp1=THM;
            ppp2=app.acc_response_psd;
            
            y_label=ylab_acc;
            %
            if(iu==1)
                t_string=sprintf('Acceleration Response PSD  x=%g in',app.xx);
            else
                t_string=sprintf('Acceleration Response PSD  x=%g m',app.xx);    
            end
            %
            leg1=sprintf('Base Input %6.3g GRMS',input_rms);
            leg2=sprintf('Mass Response %6.3g GRMS',accel_rms);
        
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
        
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(vflag==1)
            
                if(iu==1)
                    t_string=sprintf('Relative Velocity Response PSD  x=%g in, %7.3g %s RMS',app.xx,velox_rms,sv);
                else
                    t_string=sprintf('Relative Velocity Response PSD  x=%g m, %7.3g %s RMS',app.xx,velox_rms,sv); 
                end
            
                y_label=ylab_rv;
            %
                ppp=app.rv_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            %
            end
            
            if(iu==2)
                app.rd_response_psd(:,2)=app.rd_response_psd(:,2)*1.0e+06;
            end

            if(rflag==1)
            
                if(iu==1)
                    t_string=sprintf('Relative displacement PSD  x=%g in, %6.3g %s RMS',app.xx,disp_rms,dd);
                else
                    t_string=sprintf('Relative displacement PSD  x=%g m,  %6.3g %s RMS',app.xx,disp_rms,dd);       
                end
                
                y_label=ylab_rd;
            %
                ppp=app.rd_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(shear_flag==1)
                
                if(iu==1)
                    t_string=sprintf('Shear Force PSD  x=%g in,  %7.4g %s RMS',app.xx,shear_rms,sh);
                else
                    t_string=sprintf('Shear Force PSD  x=%g m,  %7.4g %s RMS',app.xx,shear_rms,sh);
                end
                
                y_label=ylab_sh;
            
                ppp=app.shear_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
            end    

            if(sflag==1)
      
                if(iu==1)
                    t_string=sprintf('Bending moment PSD  x=%g in,  %7.4g %s RMS',app.xx,bm_rms,sb);
                else
                    t_string=sprintf('Bending moment PSD  x=%g m,  %7.4g %s RMS',app.xx,bm_rms,sb);
                end
                
                y_label=ylab_bm;

                ppp=app.moment_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
                
                if(iu==1)
                    t_string=sprintf('Stress Response PSD  x=%g in,  %7.4g %s RMS',app.xx,stress_rms,su);
                else
                    t_string=sprintf('Stress Response PSD  x=%g m,  %7.4g %s RMS',app.xx,stress_rms,su);
                end
                
                y_label=ylab_stress;

                ppp=app.stress_response_psd;
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            m=1;
            
            if(m==1)
    
                xxL=(0:1:20)*app.L/20;
            
                num=length(xxL);
            
                amax=0;
                vmax=0;
                rdmax=0;
                smax=0;
                bmmax=0;
                shmax=0;
            
                progressbar;
            
                for i=1:num
                
                    progressbar((i-1)/num);
                
                    app.xx=xxL(i);
                
                    [acc_trans,rv_trans,rd_trans,moment_trans,shear_trans]=...
                      beam_bending_trans_core_alt(app.beta,app.C,app.sq_mass,app.xx,iu,app.LBC,app.RBC,freq,app.part,ffn,damp,app.E,app.MOI);
            
                    if( acc_trans(1,2)>1.0e-12 && max(acc_trans(:,2))>=1.0e-12)		  
            	       try
            	           [app.acc_response_psdm]=psd_mult_trans(PSD_in,acc_trans);
                           [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psdm(:,1),app.acc_response_psdm(:,2)  );
                       catch
            	       end
                    end
                  
                    if( rv_trans(1,2)>1.0e-12 && max(rv_trans(:,2))>=1.0e-12)
            	       try
            	           [app.rv_response_psdm]=psd_mult_trans(PSD_in,rv_trans);
            	           [~,velox_rms]=calculate_PSD_slopes_no(  app.rv_response_psdm(:,1),app.rv_response_psdm(:,2)  );                       
            	       catch
            	       end
                    end
                    if( rd_trans(1,2)>1.0e-12 && max(rd_trans(:,2))>=1.0e-12)
                       try             
                           [app.rd_response_psdm]=psd_mult_trans(PSD_in,rd_trans);
                           [~,disp_rms]=calculate_PSD_slopes_no(  app.rd_response_psdm(:,1),app.rd_response_psdm(:,2)  );           
                       catch
                       end
                    end            
            
                    if(disp_rms>=rdmax)
                        rdmax=disp_rms;
                        xrdmax=app.xx;
                    end       
                    
                    if(velox_rms>=vmax)
                        vmax=velox_rms;
                        xvmax=app.xx;
                    end
                    
                    if(accel_rms>=amax)
                        amax=accel_rms;
                        xamax=app.xx;
                    end
                    
                    if( shear_trans(1,2)>1.0e-12 && max(shear_trans(:,2))>=1.0e-12)
                        try
                            [sym]=psd_mult_trans(PSD_in,shear_trans);
                            [~,sh_rms]=calculate_PSD_slopes_no(  sym(:,1),sym(:,2)  );
                        catch 
                            disp('shear fail')
                        end
                    end    
                    
                    if(shear_rms>=shmax)
                        shmax=sh_rms;
                        xshmax=app.xx;
                    end           
                    
                    if( moment_trans(1,2)>1.0e-12 && max(moment_trans(:,2))>=1.0e-12)
            	       try
              
                            [mym]=psd_mult_trans(PSD_in,moment_trans);			
                
                            [~,bm_rms]=calculate_PSD_slopes_no(  mym(:,1),mym(:,2)  );    
               
                            srm(:,1)=mym(:,1);    
                            srm(:,2)=(app.cna/app.MOI)^2*mym(:,2);
            			
                            [~,stress_rms]=calculate_PSD_slopes_no(  srm(:,1),srm(:,2)  ); 
            
            	       catch
            	       end
                    end
                   
                    if(stress_rms>=smax)
                        smax=stress_rms;
                        xsmax=app.xx;
                    end       
                    if(bm_rms>=bmmax)
                        bmmax=bm_rms;
                        xbmmax=app.xx;
                    end           
            
                end
                
                if(iu==2)
                %    disp_rms=disp_rms*1000;
                end
            
                progressbar(1);
            
                disp(' ');
                disp(' Maximum Overall Levels Across Entire Length ');
                disp(' ');
            
                fprintf('  Acceleration Response PSD  x=%g in, %7.3g GRMS \n',xamax,amax);    
                
                if(iu==1)
                    t_string=sprintf('  Relative Velocity PSD  x=%g in, %7.3g %s RMS',xvmax,vmax,sv);
                else
                    t_string=sprintf('  Relative Velocity PSD  x=%g m, %7.3g %s RMS',xvmax,vmax,sv); 
                end
                disp(t_string);
                
                if(iu==1)
                    t_string=sprintf('  Relative displacement PSD  x=%g in, %7.3g %s RMS',xrdmax,rdmax,dd);
                else
                    t_string=sprintf('  Relative displacement PSD  x=%g m, %7.3g %s RMS',xrdmax,rdmax,dd); 
                end         
                disp(t_string);   
            
                if(iu==1)
                    t_string=sprintf('\n  Bending moment PSD  x=%g in,  %7.4g %s RMS',xbmmax,bmmax,sb);
                else
                    t_string=sprintf('  Bending moment PSD  x=%g m,  %7.4g %s RMS',xbmmax,bmmax,sb);
                end                
                disp(t_string);  
                
                if(iu==1)
                    t_string=sprintf('  Shear Force PSD  x=%g in,  %7.4g %s RMS',xshmax,shmax,sh);
                else
                    t_string=sprintf('  Shear Force PSD  x=%g m,  %7.4g %s RMS',xshmax,shmax,sh);
                end    
                disp(t_string);      
                
                if(iu==1)
                    t_string=sprintf('  Stress Response PSD  x=%g in,  %7.4g %s RMS',xsmax,smax,su);
                else
                    t_string=sprintf('  Stress Response PSD  x=%g m,  %7.4g %s RMS',xsmax,smax,su);
                end    
                disp(t_string);    
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.SaveButton_PSD.Enable='on';
            
            disp(' ');
            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
           
        end

        % Button pushed function: CalculateBaseInputArbitrary
        function CalculateBaseInputArbitraryButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end         
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            fig_num=1;
            iu=1;
            
            FS = app.InputArrayNameEditField.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end            
        
            %
            t=THM(:,1);
            num_steps=length(t);
            abase=THM(:,2);
            %
            dt=mean(diff(t));
            %            
        
            disp(' ')
            disp(' Time Step ');
            dtmin=min(diff(t));
            dtmax=max(diff(t));
            %
            fprintf(' dtmin  = %8.4g sec  \n',dtmin);
            fprintf(' dt     = %8.4g sec  \n',dt);
            fprintf(' dtmax  = %8.4g sec  \n',dtmax);
            %
            disp(' ')
            disp(' Sample Rate ');
            fprintf(' srmin  = %8.4g samples/sec  \n',1/dtmax);
            fprintf(' sr     = %8.4g samples/sec  \n',1/dt);
            fprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
  
            %
            if(((dtmax-dtmin)/dt)>0.01)
                disp(' ')
                warndlg(' Warning:  time step is not constant.');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
          
            app.xx=app.ResponseLocationEditField_arbitrary.Value;  
            
            nu=app.NumberofModesListBox_arbitrary.Value;        
            number_modes=round(str2double(nu));

            EI=app.E*app.MOI;
            
            PF=app.part;

            n=number_modes;
              
            [ModeShape,ModeShape_dd,ModeShape_ddd]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);         

            disp(' ');
            
            disp('        Natural    Participation   Damping  ');
            disp('Mode   Frequency      Factor        Ratio   ');
            %
            for i=1:length(app.fn)
                fprintf('%d  %10.4g Hz   %10.4g   %8.4g \n',i,app.fn(i),app.part(i),app.dm(i) );
            end    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            nrd=zeros(num_steps,n);
            nrv=zeros(num_steps,n);
            nra=zeros(num_steps,n); 
            %     
            [a1,a2,ra_b1,ra_b2,ra_b3,rv_b1,rv_b2,rv_b3,rd_b1,rd_b2,rd_b3]=...
                                              srs_coefficients_avd(app.fn,app.dm,dt);
            %

            xxu=sort(unique([app.xx app.L*[0 0.5 1]]));
            
            nxx=length(xxu);
            
            rd=zeros(num_steps,nxx);
            rv=zeros(num_steps,nxx);
            ra=zeros(num_steps,nxx);    
            bm=zeros(num_steps,nxx);
            shear=zeros(num_steps,nxx);
            %
            app.ZZ=zeros(nxx,number_modes);  
            
            for j=1:number_modes
            %
                if(abs(PF(j))>0)
            %
                    yp=-abase*PF(j);
            %
                    back   =[     1, -a1(j), -a2(j) ];
            %
            %   relative velocity
            %
                    clear forward;
                    forward=[ rv_b1(j),  rv_b2(j),  rv_b3(j) ];    
                    nrv(:,j)=filter(forward,back,yp);
            %
            %   relative displacement
            %
                    clear forward;
                    forward=[ rd_b1(j),  rd_b2(j),  rd_b3(j) ];      
                    nrd(:,j)=filter(forward,back,yp);
            %
            %   relative acceleraiton
            %
                    clear forward;  
                    forward=[ ra_b1(j),  ra_b2(j),  ra_b3(j) ];    
                    nra(:,j)=filter(forward,back,yp);                
            %            
                    for k=1:nxx      
                     
                       arg=app.beta(j)*xxu(k);
                       app.ZZ(k,j)=ModeShape(arg,app.C(j),app.sq_mass);
            %
                       rd(:,k)= rd(:,k) +app.ZZ(k,j)*nrd(:,j);
                       rv(:,k)= rv(:,k) +app.ZZ(k,j)*nrv(:,j);
                       ra(:,k)= ra(:,k) +app.ZZ(k,j)*nra(:,j);                
                                    
                       ZZdd=ModeShape_dd(arg,app.C(j),app.beta(j),app.sq_mass);
                       ZZddd=ModeShape_ddd(arg,app.C(j),app.beta(j),app.sq_mass);
                          bm(:,k)=    bm(:,k) +ZZdd*nrd(:,j);  
                       shear(:,k)= shear(:,k) +ZZddd*nrd(:,j);               
                    end
            %        
                end
            end
            %
            bm=bm*EI;
            shear=shear*EI;
            %

            nmn=length(abase);
            acc=zeros(nmn,nxx);
            %
            for i=1:nxx
                acc(:,i)=ra(:,i)+abase;
            end

            if(iu==1)
                rd=rd*386;
                rv=rv*386;        
                bm=bm*386; 
                shear=shear*386;
            else
                rd=rd*9.81;
                rv=rv*9.81;       
                bm=bm*9.81;     
                shear=shear*9.81;
            end

            if(iu==1)
                  fprintf('\n Absolute Acceleration = %8.4g G at %g in \n',max(abs(acc(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g G at %g in \n',max(abs(acc(:,2))),xxu(2));  
                  fprintf('                       = %8.4g G at %g in \n',max(abs(acc(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                     fprintf('                       = %8.4g in/sec at %g in \n',max(abs(acc(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Absolute  Acceleration = %8.4g G at %g m \n',max(abs(acc(:,1))),xxu(1)); 
                  fprintf('                        = %8.4g G at %g m \n',max(abs(acc(:,2))),xxu(2));  
                  fprintf('                        = %8.4g G at %g m \n',max(abs(acc(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                       = %8.4g G at %g m \n',max(abs(rv(:,4))),xxu(4));               
                  end
            end    
            
            if(iu==1)
                  fprintf('\n Relative Velocity = %8.4g in/sec at %g in \n',max(abs(rv(:,1))),xxu(1)); 
                  fprintf('                   = %8.4g in/sec at %g in \n',max(abs(rv(:,2))),xxu(2));  
                  fprintf('                   = %8.4g in/sec at %g in \n',max(abs(rv(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                  = %8.4g in/sec at %g in \n',max(abs(rv(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Relative Velocity = %8.4g m/sec at %g m \n',max(abs(rv(:,1))),xxu(1)); 
                  fprintf('                   = %8.4g m/sec at %g m \n',max(abs(rv(:,2))),xxu(2));  
                  fprintf('                   = %8.4g m/sec at %g m \n',max(abs(rv(:,3))),vxx(3));           
           
                  if(length(xxu)==4)
                    fprintf('                  = %8.4g m/sec at %g m \n',max(abs(rv(:,4))),xxu(4));               
                  end
            end    

            if(iu==1)
                  fprintf('\n Relative displacement = %8.4g in at %g in \n',max(abs(rd(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g in at %g in \n',max(abs(rd(:,2))),xxu(2));  
                  fprintf('                       = %8.4g in at %g in \n',max(abs(rd(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                      = %8.4g in at %g in \n',max(abs(rd(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Relative displacement = %8.4g mm at %g m \n',max(abs(rd(:,1))),xxu(1)); 
                  fprintf('                       = %8.4g mm at %g m \n',max(abs(rd(:,2))),xxu(2));  
                  fprintf('                       = %8.4g mm at %g m \n',max(abs(rd(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                      = %8.4g mm at %g m \n',max(abs(rd(:,4))),xxu(4));               
                  end
            end    

            if(iu==1)
                  fprintf('\n Bending moment = %8.4g in-lbf at %g in \n',max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                = %8.4g in-lbf at %g in \n',max(abs(bm(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Bending moment = %8.4g Nm at %g m \n',max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g Nm at %g m \n',max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g Nm at %g m \n',max(abs(bm(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g in-lbf at %g m \n',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    

            if(iu==1)
                  fprintf('\n  Shear Force = %8.4g lbf at %g in \n',max(abs(shear(:,1))),xxu(1)); 
                  fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,2))),xxu(2));  
                  fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                     fprintf('                = %8.4g lbf at %g in \n',max(abs(shear(:,4))),xxu(4));               
                  end
            
            else
                  fprintf('\n Shear Force = %8.4g N at %g m \n',max(abs(shear(:,1))),xxu(1)); 
                  fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,2))),xxu(2));  
                  fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g N at %g m \n',max(abs(shear(:,4))),xxu(4));               
                  end   
            end    

            c=app.cna;

            if(iu==1)
                
                  fprintf('\n Distance from neutral axis = %8.4g in \n',app.cna);
                
                  fprintf('\n Bending Stress = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));          
           
                  if(length(xxu)==4)
                    fprintf('                = %8.4g psi at %g in \n',(c/app.MOI)*max(abs(bm(:,4))),xxu(4));               
                  end
            
            else
                
                  fprintf('\n Distance from neutral axis = %8.4g m \n',app.cna);
                
                  fprintf('\n Bending Stress = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,1))),xxu(1)); 
                  fprintf('                = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,2))),xxu(2));  
                  fprintf('                = %8.4g Pa at %g m \n',(c/app.MOI)*max(abs(bm(:,3))),xxu(3));
            
                  if(length(xxu)==4)
                    fprintf('                = %8.4g Pa at %g m \n',max(abs(bm(:,4))),xxu(4));               
                  end   
            end    

            t=fix_size(t);
            acc=fix_size(acc);
            rd=fix_size(rd);

            for i=1:nxx
                if(app.xx==xxu(i))
                    k=i;
                    break;
                end
            end

            bstress=(c/app.MOI)*bm(:,k);

            bstress=fix_size(bstress);

            figure(fig_num)
            fig_num=fig_num+1;

            plot(t,abase);

            title('Base Input Acceleration');
            ylabel('Accel(G) ');
            xlabel('Time(sec)'); 
            grid on;

            figure(fig_num)
            fig_num=fig_num+1;

            plot(t,acc(:,k)); 
            app.acceleration=[t acc(:,k)];

            if(iu==1)
                out1=sprintf('Response Absolute Acceleration at %g in',app.xx);        
            else
                out1=sprintf('Response Absolute Acceleration at %g m',app.xx);
            end
            title(out1);
            ylabel('Accel(G) ');
            xlabel('Time(sec)'); 
            grid on;

            figure(fig_num)
            fig_num=fig_num+1;

            if(iu==1)
                plot(t,rv(:,k));
                out1=sprintf('Relative Velocity at %g in',app.xx);
                ylabel('Rel Vel (in/sec)');      
            else
                plot(t,rv(:,k));
                out1=sprintf('Relative Velocity  at %g m',app.xx);
                ylabel('Rel Vel (m/sec)');        
            end
            app.relative_velocity=[t rv(:,k)];   
            title(out1);
            xlabel('Time(sec)'); 
            grid on;     

            figure(fig_num)
            fig_num=fig_num+1;

            if(iu==1)
                plot(t,rd(:,k));
                out1=sprintf('Relative displacement at %g in',app.xx);
                ylabel('Rel Disp (inch)');      
            else
                plot(t,rd(:,k)*1000);
                out1=sprintf('Relative displacement  at %g m',app.xx);
                ylabel('Rel Disp (mm)');        
            end
            app.relative_displacement=[t rd(:,k)];       
            title(out1);
            xlabel('Time(sec)'); 
            grid on;    

            figure(fig_num)

            stss=std(bstress);
        
            if(iu==1)
                plot(t,bstress);
                out1=sprintf('Bending Stress at %g in, %8.4g psi RMS',app.xx,stss);
                ylabel('Stress (psi)');      
            else
                plot(t,bstress);
                out1=sprintf('Bending Stress  at %g m, %8.4g Pa RMS',app.xx,stss);
                ylabel('Stress (Pa)');        
            end
            app.bending_stress=[t bstress];   
            title(out1);
            xlabel('Time(sec)'); 
            grid on;        

            app.SaveButton_arbitrary.Enable='on';
            
            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
        
 
        end

        % Callback function
        function CalculateBaseInputMDOFSRSButtonPushed(app, event)
            
            CloseFigures(app);
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end      
            
            try
                FS=strtrim(app.InputArrayNameEditField_MDOFSRS.Value);
                THM=evalin('base',FS);   
            catch
                warndlg('Input array not found');
                return;
            end
            
            num_srs=length(THM(:,1));
            num_fn=length(app.fn);   
            
            fig_num=1;
            
            iu=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
            
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=THM;
            fmin=THM(1,1);
            fmax=THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.fn(1)<THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            if(app.fn(num_fn)>THM(num_srs,1))
               warndlg(' Last Natural Frequency < Last Specification Frequency ');
               return
            end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
              
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  
            
            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);
            
             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
                          
            f=THM(:,1);
            a=THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            app.xx=[0. 0.5 1]*app.L;
            nxx=length(app.xx);
            
            num_modes=str2num(app.NumberofModesListBox_MDOFSRS.Value);
            
            if(num_modes>num_fn)
                num_modes=num_fn;
            end
            
            [ModeShape,~,~]=beam_bending_modes_shapes_alt(app.LBC,app.RBC);  
             
            for i=1:nxx
               
                for j=1:num_modes
                    
                    arg=app.beta(j)*app.xx(i);
                    PFM=app.part(j)*ModeShape(arg,app.C(j),app.sq_mass);
                     
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            disp(' ');
            disp(' Pseudo Velocity');
            
            if(iu==1)
                out_a='     x(in) SRSS(in/sec)  Abssum(in/sec)';
            else
                out_a='     x(m)  SRSS(cm/sec)  Abssum(cm/sec)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),pv_srss(i),pv_abssum(i));
            end
            
            disp(' ');
            disp(' Relative Displacement');
                
            if(iu==1)
                out_a='       x(in)  SRSS(in)  Abssum(in)';
            else
                out_a='       x(m)   SRSS(mm)  Abssum(mm)';   
            end
                
            disp(' ');
            disp(out_a);
            
            for i=1:nxx
                fprintf('     %7.3f  %8.4g  %8.4g \n',app.xx(i),rd_srss(i),rd_abssum(i));
            end
            
            msgbox('Results written to Command Window');
            
        end

        % Callback function
        function CalculateAppliedForceLengthFRFButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end            
           
        end

        % Button pushed function: CalculateNaturalFrequencyButton
        function CalculateNaturalFrequencyButtonPushed(app, event)
            
            fprintf('\n * * * * * * * * * \n\n');
        
            app.L=app.LengthinEditField.Value;
            app.E=app.ElasticModuluspsiEditField.Value;
            app.rho=app.MassDensitylbmin3EditField.Value;
            
            app.LengthinEditField_trans.Value=app.L; 
            app.LengthinEditField_sine.Value=app.L;
            app.LengthinEditField_arbitrary.Value=app.L;
            app.LengthinEditField_PSD.Value=app.L;
            app.LengthinEditField_HS.Value=app.L;
            
            app.rho=app.rho/386;
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=3;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=3;                
            end    
            
            if((app.LBC==2 && app.RBC==3) || (app.LBC==3 && app.RBC==2)) % pinned-free
                warndlg('case unavailable');
                return;
            end 
            
            [root]=beam_bending_roots(app.LBC,app.RBC);
    
            %%%%%%%%%%%%%%%%%%%%%
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=app.EditField_1.Value;
                thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=app.EditField_1.Value;
                wall_thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=app.EditField_1.Value;
                [area,app.MOI,app.cna]=cylinder_geometry(diameter);     
            end
            if(contains(n,'I-Beam'))
                B=app.EditField_1Label.Value;
                H=app.EditField_2Label.Value;
                Tf=app.EditField_3Label.Value;     
                Tw=app.EditField_4Label.Value;  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=app.EditField_1.Value;
                app.MOI=app.EditField_2.Value;
                app.cna=app.EditField_3.Value;       
            end
            
            rho_mpa=app.rho*area;   % mass per unit length

            fprintf('\n area=%8.4g in^2    MOI=%8.4g in^4 \n\n',area,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%
            
            npm=app.AddPointMassListBox.Value;
            
            m=0;
            x1=0;
            
            if(strcmp(npm,'Yes'))
               m=app.PointMasslbmEditField.Value;
               m=m/386;
               x1=app.DistancefromLeftEndinEditField.Value;
            end
            
            mass= m + rho_mpa*app.L;
            app.sq_mass=sqrt(mass);
            
            %%%%%%%%%%%%%%%%%%%%%
            
            n=1;

            app.fn=zeros(n,1);
            app.beta=zeros(n,1);
            cp=zeros(n,1);
            
            for i=1:n
                sss=sin(pi*x1/app.L)^2;
                term=2*m*sss + (rho_mpa*app.L);
                A=app.E*app.MOI/(app.L^3*term);
                app.omegan=pi^2*sqrt(A);
                app.fn(i)=app.omegan/(2*pi);
                app.beta(i)=pi/app.L;
            end
                                         
            app.C=zeros(n,1);
            
            app.part=zeros(n,1);
            emm=zeros(n,1);   
           
            app.LBC=2;
            app.RBC=2;
            iflag=0;
            
            if(app.LBC==2 && app.RBC==2) % pinned-pinned
                app.C=sqrt(2)*ones(n,1);
                ModeShape=@(arg,Co)(Co*sin(arg));
            %      
                for i=1:n
                   app.part(i)=(-1/(i*pi))*sqrt(2*mass)*(cos(i*pi)-1);
                end
            %       
            end 
            %    
       
            cp=cp/12; 
            ss='(ft/sec)'; 
            st='(rad/ft)';
        
            for i=1:n
                if(abs(app.part(i))<1.0e-09)
                    app.part(i)=0;
                end
            end
        
            app.omegan=2*pi*app.fn;
            
            fprintf('\n  Modal Mass unit:  lbf sec^2/in \n\n');
        
            if(iflag==0)
                sum=0;
            %
                        disp('        Natural    Participation    Effective    ');
                fprintf('Mode   Freq (Hz)      Factor        Modal Mass      \n');        
            %
                for i=1:n
                    emm(i)=app.part(i)^2;
                    fprintf('%d  %10.4g     %10.4g      %10.4g    %8.4g    %8.4g \n',i,app.fn(i),app.part(i),emm(i));
                    sum=sum+emm(i);
                end
                fprintf('\n\n modal mass sum = %8.4g lbf sec^2/in ',sum);
                fprintf('\n                = %8.4g lbm \n\n',sum*386);
            
            end
            
            num=500;            
            dx=app.L/num;
            
            app.x=zeros(num+1,1);
            app.y=zeros(num+1,6);
    
            for i=1:num+1
                app.x(i)=(i-1)*dx;
                for j=1:n
                    arg=app.beta(j)*app.x(i);
                    app.y(i,j)=ModeShape(arg,app.C(j));
                end
            end
    
            for j=1:n
                my=max(abs(app.y(:,j)));     
                app.y(:,j)=app.y(:,j)/my;
            end
            
            %%%  Leave as-is
            
            if(app.LBC==2 && app.RBC==2) % pinned-pinned
                ModeShape=@(arg,Co,sq_mass)((Co*sin(arg))/sq_mass);
                ModeShape_dd=@(arg,Co,beta,sq_mass)(beta^2*(-sqrt(2)*sin(arg))/sq_mass);        
            end  
            
            app.xx=app.L*[0 0.5 1.0];

            nxx=length(app.xx);
            
            num_fn=length(app.fn);
            
            app.ZZ=zeros(nxx,num_fn);
            %
            
            for j=1:num_fn
            %
                for k=1:nxx      
                    arg=app.beta(j)*app.xx(k);
                    app.ZZ(k,j)=ModeShape(arg,app.C(j),app.sq_mass);
                end
            %        
            end
            
            i=1;

            figure(1);
            plot(app.x,app.y(:,i));
            grid on;
            axis([0,app.L,-1.1,1.1]);

            xlabel('x (inch) ');
            ylabel('Normalized Modal displacement');
            
            sss=sprintf('Mode %d   fn=%8.4g Hz',i,app.fn(i));
            
            title(sss);
            
            sst=sprintf('mode_%d',i);
            
            assignin('base',sst,[app.x,app.y(:,i)]);
            
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            ChangeCrossSection(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=6;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(i)=v;
                    end            
                end   
            end
        end

        % Callback function
        function PlotModeShapeButtonPushed(app, event)

            i=1;

            figure(1);
            plot(app.x,app.y(:,i));
            grid on;
            axis([0,app.L,-1.1,1.1]);

            xlabel('x (inch) ');
            ylabel('Normalized Modal displacement');
            
            sss=sprintf('Mode %d   fn=%8.4g Hz',i,app.fn(i));
            
            title(sss);
            
            sst=sprintf('mode_%d',i);
            
            assignin('base',sst,[app.x,app.y(:,i)]);

        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)


            BeamBaseImage(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)


            BeamBaseImage(app);
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)


        end

        % Button pushed function: SaveTransButton
        function SaveTransButtonButtonPushed(app, event)
            n=strtrim(app.OutputFunctionListBox.Value);
            if(strcmp(n,'Relative displacement'))
                data=app.relative_disp;
            end
            if(strcmp(n,'Relative Velocity'))
                data=app.relative_vel;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.absolute_accel;
            end 
            if(strcmp(n,'Bending moment'))
                data=app.bending_moment;
            end
            if(strcmp(n,'Bending Stress'))
                data=app.bending_stress;
            end
            if(strcmp(n,'Shear Force'))
                data=app.shear_force;
            end
            output_name=strtrim(app.OutputArrayNameEditField.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');            
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFrequencyHzEditField_sine.Value=app.fn(1);
        end

        % Button pushed function: SaveButton_arbitrary
        function SaveButton_arbitraryPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_arbitrary.Value);
            if(strcmp(n,'Relative displacement'))
                data=app.relative_displacement;
            end
            if(strcmp(n,'Relative Velocity'))
                data=app.relative_velocity;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(strcmp(n,'Bending Stress'))
                data=app.bending_stress;
            end
            output_name=strtrim(app.OutputArrayNameEditField_arbitrary.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');         
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_PSD.Value);
            if(strcmp(n,'Relative displacement'))
                data=app.rd_response_psd;
            end
            if(strcmp(n,'Relative Velocity'))
                data=app.rv_response_psd;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.acc_response_psd;
            end 
            if(strcmp(n,'Bending moment'))
                data=app.moment_response_psd;
            end
            if(strcmp(n,'Bending Stress'))
                data=app.stress_response_psd;
            end
            if(strcmp(n,'Shear Force'))
                data=app.shear_response_psd;
            end
            output_name=strtrim(app.OutputArrayNameEditField_PSD.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');       
        end

        % Value changed function: NumberofModesListBox_HS
        function NumberofModesListBox_HSValueChanged(app, event)
            app.SaveButton_HS.Enable='off';
        end

        % Value changed function: BaseInputAccelGEditField
        function BaseInputAccelGEditFieldValueChanged(app, event)

            app.SaveButton_HS.Enable='off';
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)

            app.SaveButton_HS.Enable='off';
        end

        % Value changed function: ResponseLocationEditField_HS
        function ResponseLocationEditField_HSValueChanged(app, event)

            app.SaveButton_HS.Enable='off';            
        end

        % Button pushed function: SaveButton_HS
        function SaveButton_HSPushed(app, event)
        output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
        n=strtrim(app.OutputFunctionListBox_HS.Value);
            if(strcmp(n,'Relative Displacement'))
                data=app.relative_disp;
            end
            if(strcmp(n,'Absolute Acceleration'))
                data=app.absolute_accel;
            end 
            
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Selection changed function: TabsLocationButtonGroup
        function TabsLocationButtonGroupSelectionChanged(app, event)

            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Value changed function: AddPointMassListBox
        function AddPointMassListBoxValueChanged(app, event)
            APM(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISimplySupportedBeamwithPointMassUIFigure = uifigure('Visible', 'off');
            app.UISimplySupportedBeamwithPointMassUIFigure.Name = 'UI Simply-Supported Beam with Point Mass';
            app.TabGroup = uitabgroup(app.UISimplySupportedBeamwithPointMassUIFigure);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'numeric');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Enable = 'off';
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Enable = 'off';
            app.LeftBCListBox.Value = 'Pinned';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Enable = 'off';
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Enable = 'off';
            app.RightBCListBox.Value = 'Pinned';
            app.PointMasslbmEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.PointMasslbmEditFieldLabel.Text = 'Point Mass (lbm)';
            app.PointMasslbmEditField = uieditfield(app.GeometryBCsTab, 'numeric');
            app.AddPointMassListBoxLabel = uilabel(app.GeometryBCsTab);
            app.AddPointMassListBoxLabel.Text = 'Add Point Mass';
            app.AddPointMassListBox = uilistbox(app.GeometryBCsTab);
            app.AddPointMassListBox.Items = {'Yes', 'No'};
            app.AddPointMassListBox.ValueChangedFcn = createCallbackFcn(app, @AddPointMassListBoxValueChanged, true);
            app.AddPointMassListBox.Value = 'Yes';
            app.DistancefromLeftEndinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.DistancefromLeftEndinEditFieldLabel.Text = 'Distance from Left End (in)';
            app.DistancefromLeftEndinEditField = uieditfield(app.GeometryBCsTab, 'numeric');
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialTab, 'numeric');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialTab, 'numeric');
            app.MaterialLabel = uilabel(app.MaterialTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other', ''};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.CalculateNaturalFrequencyButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequencyButtonPushed, true);
            app.CalculateNaturalFrequencyButton.Text = 'Calculate Natural Frequency';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {''};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.BaseInputTransTab = uitab(app.TabGroup);
            app.BaseInputTransTab.Title = 'Base Input Trans';
            app.CalculateBaseInputTrans = uibutton(app.BaseInputTransTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.ResponseLocationinEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.ResponseLocationinEditFieldLabel.Text = 'Response Location (in)';
            app.ResponseLocationinEditField = uieditfield(app.BaseInputTransTab, 'numeric');
            app.StartFrequencyHzEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.StartFrequencyHzEditFieldLabel.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.BaseInputTransTab, 'numeric');
            app.EndFrequencyHzEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.EndFrequencyHzEditFieldLabel.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.BaseInputTransTab, 'numeric');
            app.NumberofModesListBoxLabel = uilabel(app.BaseInputTransTab);
            app.NumberofModesListBoxLabel.Text = 'Number of Modes';
            app.NumberofModesListBox = uilistbox(app.BaseInputTransTab);
            app.NumberofModesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox.Value = '1';
            app.LengthinEditField_2Label = uilabel(app.BaseInputTransTab);
            app.LengthinEditField_2Label.Text = 'Length (in)';
            app.LengthinEditField_trans = uieditfield(app.BaseInputTransTab, 'numeric');
            app.LengthinEditField_trans.Editable = 'off';
            app.SaveTransButton = uibutton(app.BaseInputTransTab, 'push');
            app.SaveTransButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransButtonButtonPushed, true);
            app.SaveTransButton.Text = 'Save';
            app.OutputFunctionListBoxLabel = uilabel(app.BaseInputTransTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox = uilistbox(app.BaseInputTransTab);
            app.OutputFunctionListBox.Items = {'Relative Displacement', 'Relative Velocity', 'Absolute Acceleration', 'Bending Moment', 'Bending Stress', 'Shear Force'};
            app.OutputFunctionListBox.Value = 'Relative Displacement';
            app.OutputArrayNameEditFieldLabel = uilabel(app.BaseInputTransTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.BaseInputTransTab, 'text');
            app.ImageBeamBase = uiimage(app.BaseInputTransTab);
            app.BaseInputSineTab = uitab(app.TabGroup);
            app.BaseInputSineTab.Title = 'Base Input Sine';
            app.CalculateBaseInputSine = uibutton(app.BaseInputSineTab, 'push');
            app.CalculateBaseInputSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSineButtonPushed, true);
            app.CalculateBaseInputSine.Text = 'Calculate';
            app.ResponseLocationinLabel_2 = uilabel(app.BaseInputSineTab);
            app.ResponseLocationinLabel_2.Text = 'Response Location (in)';
            app.ResponseLocationEditField_sine = uieditfield(app.BaseInputSineTab, 'numeric');
            app.ExcitationFrequencyHzLabel = uilabel(app.BaseInputSineTab);
            app.ExcitationFrequencyHzLabel.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzEditField_sine = uieditfield(app.BaseInputSineTab, 'numeric');
            app.BaseAccelerationGEditFieldLabel = uilabel(app.BaseInputSineTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.BaseInputSineTab, 'numeric');
            app.NumberofModesListBox_2Label = uilabel(app.BaseInputSineTab);
            app.NumberofModesListBox_2Label.Text = 'Number of Modes';
            app.NumberofModesListBox_sine = uilistbox(app.BaseInputSineTab);
            app.NumberofModesListBox_sine.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_sine.Value = '1';
            app.LengthinEditField_2Label_2 = uilabel(app.BaseInputSineTab);
            app.LengthinEditField_2Label_2.Text = 'Length (in)';
            app.LengthinEditField_sine = uieditfield(app.BaseInputSineTab, 'numeric');
            app.LengthinEditField_sine.Editable = 'off';
            app.SettoFirstfnButton = uibutton(app.BaseInputSineTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.ImageBeamBase_sine = uiimage(app.BaseInputSineTab);
            app.BaseInputHalfSineTab = uitab(app.TabGroup);
            app.BaseInputHalfSineTab.Title = 'Base Input Half-Sine';
            app.CalculateBaseInput_HS = uibutton(app.BaseInputHalfSineTab, 'push');
            app.CalculateBaseInput_HS.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInput_HSButtonPushed, true);
            app.CalculateBaseInput_HS.Text = 'Calculate';
            app.ImageBeamBase_HS = uiimage(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_2Label_2 = uilabel(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_2Label_2.Text = 'Number of Modes';
            app.NumberofModesListBox_HS = uilistbox(app.BaseInputHalfSineTab);
            app.NumberofModesListBox_HS.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_HS.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBox_HSValueChanged, true);
            app.NumberofModesListBox_HS.Value = '1';
            app.ResponseLocationinLabel_4 = uilabel(app.BaseInputHalfSineTab);
            app.ResponseLocationinLabel_4.Text = 'Response Location (in)';
            app.ResponseLocationEditField_HS = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.ResponseLocationEditField_HS.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationEditField_HSValueChanged, true);
            app.LengthinEditField_2Label_5 = uilabel(app.BaseInputHalfSineTab);
            app.LengthinEditField_2Label_5.Text = 'Length (in)';
            app.LengthinEditField_HS = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.LengthinEditField_HS.Editable = 'off';
            app.ResponsetoHalfSineBaseInputLabel = uilabel(app.BaseInputHalfSineTab);
            app.ResponsetoHalfSineBaseInputLabel.Text = 'Response to Half-Sine Base Input';
            app.BaseInputAccelGEditFieldLabel = uilabel(app.BaseInputHalfSineTab);
            app.BaseInputAccelGEditFieldLabel.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.BaseInputAccelGEditField.ValueChangedFcn = createCallbackFcn(app, @BaseInputAccelGEditFieldValueChanged, true);
            app.DurationsecEditFieldLabel = uilabel(app.BaseInputHalfSineTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.BaseInputHalfSineTab, 'numeric');
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.SaveButton_HS = uibutton(app.BaseInputHalfSineTab, 'push');
            app.SaveButton_HS.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_HSPushed, true);
            app.SaveButton_HS.Text = 'Save';
            app.OutputFunctionListBox_2Label_3 = uilabel(app.BaseInputHalfSineTab);
            app.OutputFunctionListBox_2Label_3.Text = 'Output Function';
            app.OutputFunctionListBox_HS = uilistbox(app.BaseInputHalfSineTab);
            app.OutputFunctionListBox_HS.Items = {'Absolute Acceleration', 'Relative Displacement'};
            app.OutputFunctionListBox_HS.Value = 'Absolute Acceleration';
            app.OutputArrayNameEditField_2Label_3 = uilabel(app.BaseInputHalfSineTab);
            app.OutputArrayNameEditField_2Label_3.Text = 'Output Array Name';
            app.OutputArrayNameEditField_HS = uieditfield(app.BaseInputHalfSineTab, 'text');
            app.BaseInputPSDTab = uitab(app.TabGroup);
            app.BaseInputPSDTab.Title = 'Base Input PSD';
            app.CalculateBaseInputPSD = uibutton(app.BaseInputPSDTab, 'push');
            app.CalculateBaseInputPSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputPSDButtonPushed, true);
            app.CalculateBaseInputPSD.Text = 'Calculate';
            app.ImageBeamBase_PSD = uiimage(app.BaseInputPSDTab);
            app.NumberofModesListBox_2Label_3 = uilabel(app.BaseInputPSDTab);
            app.NumberofModesListBox_2Label_3.Text = 'Number of Modes';
            app.NumberofModesListBox_PSD = uilistbox(app.BaseInputPSDTab);
            app.NumberofModesListBox_PSD.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_PSD.Value = '1';
            app.ResponseLocationinLabel = uilabel(app.BaseInputPSDTab);
            app.ResponseLocationinLabel.Text = 'Response Location (in)';
            app.ResponseLocationEditField_PSD = uieditfield(app.BaseInputPSDTab, 'numeric');
            app.LengthinEditField_2Label_4 = uilabel(app.BaseInputPSDTab);
            app.LengthinEditField_2Label_4.Text = 'Length (in)';
            app.LengthinEditField_PSD = uieditfield(app.BaseInputPSDTab, 'numeric');
            app.LengthinEditField_PSD.Editable = 'off';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.BaseInputPSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.InputArrayNameEditField_2Label = uilabel(app.BaseInputPSDTab);
            app.InputArrayNameEditField_2Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_PSD = uieditfield(app.BaseInputPSDTab, 'text');
            app.SaveButton_PSD = uibutton(app.BaseInputPSDTab, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputFunctionListBox_2Label_2 = uilabel(app.BaseInputPSDTab);
            app.OutputFunctionListBox_2Label_2.Text = 'Output Function';
            app.OutputFunctionListBox_PSD = uilistbox(app.BaseInputPSDTab);
            app.OutputFunctionListBox_PSD.Items = {'Relative Displacement', 'Relative Velocity', 'Absolute Acceleration', 'Bending Moment', 'Bending Stress', 'Shear Force'};
            app.OutputFunctionListBox_PSD.Value = 'Relative Displacement';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.BaseInputPSDTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.BaseInputPSDTab, 'text');
            app.BaseInputArbitraryTab = uitab(app.TabGroup);
            app.BaseInputArbitraryTab.Title = 'Base Input Arbitrary';
            app.CalculateBaseInputArbitrary = uibutton(app.BaseInputArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitraryButtonPushed, true);
            app.CalculateBaseInputArbitrary.Text = 'Calculate';
            app.ResponseLocationinLabel_3 = uilabel(app.BaseInputArbitraryTab);
            app.ResponseLocationinLabel_3.Text = 'Response Location (in)';
            app.ResponseLocationEditField_arbitrary = uieditfield(app.BaseInputArbitraryTab, 'numeric');
            app.NumberofModesListBox_3Label = uilabel(app.BaseInputArbitraryTab);
            app.NumberofModesListBox_3Label.Text = 'Number of Modes';
            app.NumberofModesListBox_arbitrary = uilistbox(app.BaseInputArbitraryTab);
            app.NumberofModesListBox_arbitrary.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox_arbitrary.Value = '1';
            app.LengthinEditField_2Label_3 = uilabel(app.BaseInputArbitraryTab);
            app.LengthinEditField_2Label_3.Text = 'Length (in)';
            app.LengthinEditField_arbitrary = uieditfield(app.BaseInputArbitraryTab, 'numeric');
            app.LengthinEditField_arbitrary.Editable = 'off';
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel = uilabel(app.BaseInputArbitraryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.InputArrayNameEditFieldLabel = uilabel(app.BaseInputArbitraryTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.BaseInputArbitraryTab, 'text');
            app.ImageBeamBase_arbitrary = uiimage(app.BaseInputArbitraryTab);
            app.SaveButton_arbitrary = uibutton(app.BaseInputArbitraryTab, 'push');
            app.SaveButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_arbitraryPushed, true);
            app.SaveButton_arbitrary.Text = 'Save';
            app.OutputFunctionListBox_2Label = uilabel(app.BaseInputArbitraryTab);
            app.OutputFunctionListBox_2Label.Text = 'Output Function';
            app.OutputFunctionListBox_arbitrary = uilistbox(app.BaseInputArbitraryTab);
            app.OutputFunctionListBox_arbitrary.Items = {'Absolute Acceleration', 'Relative Velocity', 'Relative Displacement', 'Bending Stress'};
            app.OutputFunctionListBox_arbitrary.Value = 'Absolute Acceleration';
            app.OutputArrayNameEditField_2Label = uilabel(app.BaseInputArbitraryTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_arbitrary = uieditfield(app.BaseInputArbitraryTab, 'text');
            app.ReturnButton = uibutton(app.UISimplySupportedBeamwithPointMassUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.beam_bending_aver10byTomIrvineLabel = uilabel(app.UISimplySupportedBeamwithPointMassUIFigure);
            app.beam_bending_aver10byTomIrvineLabel.Text = {'beam_simply_supported_point_mass_a    ver 1.2  by Tom Irvine      '; ''; 'This script calculates the natural frequency of a simply-supported beam with an optional point mass via the Rayleigh method.'};
            app.TabsLocationButtonGroup = uibuttongroup(app.UISimplySupportedBeamwithPointMassUIFigure);
            app.TabsLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TabsLocationButtonGroupSelectionChanged, true);
            app.TabsLocationButtonGroup.Title = 'Tabs Location';
            app.TopButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.TopButton.Text = 'Top';
            app.RightButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            % Show the figure after all components are created
            app.UISimplySupportedBeamwithPointMassUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beam_simply_supported_point_mass_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISimplySupportedBeamwithPointMassUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISimplySupportedBeamwithPointMassUIFigure)
        end
    end
end