classdef classical_pulse_applied_force_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassicAppliedForceUIFigure     matlab.ui.Figure
        EnterDataPanel                  matlab.ui.container.Panel
        MasslbmEditField                matlab.ui.control.NumericEditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        InitialDisplacementinEditField  matlab.ui.control.NumericEditField
        InitialDisplacementinEditFieldLabel  matlab.ui.control.Label
        InitialVelocityinsecEditField   matlab.ui.control.NumericEditField
        InitialVelocityinsecEditFieldLabel  matlab.ui.control.Label
        UnitListBox                     matlab.ui.control.ListBox
        UnitListBoxLabel                matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        ForcelbfEditField               matlab.ui.control.NumericEditField
        ForcelbfEditFieldLabel          matlab.ui.control.Label
        QEditField                      matlab.ui.control.NumericEditField
        QEditFieldLabel                 matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        PulseTypeListBox                matlab.ui.control.ListBox
        PulseTypeListBoxLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        TimeHistoryResponseTab          matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SelectDataListBox               matlab.ui.control.ListBox
        SelectDataListBoxLabel          matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ResponseDurationsecEditField    matlab.ui.control.NumericEditField
        ResponseDurationsecEditFieldLabel  matlab.ui.control.Label
        fnHzEditField                   matlab.ui.control.NumericEditField
        fnHzEditFieldLabel              matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SRSTab                          matlab.ui.container.Tab
        OutputArrayNameEditField_2      matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        SelectDataListBox_2             matlab.ui.control.ListBox
        SelectDataListBox_2Label        matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        EndFreqHzEditField              matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.NumericEditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        fn       %  natural frequency
        Q        %  amplification factor
        nat      %  analysis type
        npt      %  pulse type
        resp_dur %  response duration
        iunit    %  amplitude unit
        a_srs    % 
        v_srs    % 
        d_srs    %
        tf_srs   %
        force_th  % 
        trans_force_th  % 
        input_force_th  %
        d_th     % 
        v_th     %
        a_th     %
        FU       %
        VU       %
        DU       %
        MU       %
        TU       %
        stiff    %
        mass     %
        damp     %
        t        %
        v0       %
        d0       %
        amp      %
        dur      %
    end
    
    methods (Access = private)

        function prep(app)

            app.resp_dur=app.ResponseDurationsecEditField.Value;
            app.npt=strtrim(app.PulseTypeListBox.Value);
            
            ntus=app.UnitListBox.Value;

            if(contains(ntus,'msec'))
                app.TU=2;
            else
                app.TU=1;
            end   

            app.Q=app.QEditField.Value;            
            
            app.mass=app.MasslbmEditField.Value;
            
            iu=app.UnitsListBox.Value;
            
            if(contains(iu,'in'))
                app.iunit=1;
                app.mass=app.mass/386;
            else
                app.iunit=2;
            end

            app.fn=app.fnHzEditField.Value;
            app.damp=1/(2*app.Q);
            omegan=2.*pi*app.fn;
            app.stiff=app.mass*omegan^2;

            Items = strtrim(app.PulseTypeListBox.Items);
            im = strtrim(app.PulseTypeListBox.Value);
            app.npt = find(strcmp(Items, im));

            app.d0=app.InitialDisplacementinEditField.Value;
            app.v0=app.InitialVelocityinsecEditField.Value;
             
            if(app.iunit==2)
               app.d0=app.d0/1000; 
            end

            app.resp_dur=app.ResponseDurationsecEditField.Value;
            app.amp=app.ForcelbfEditField.Value; 

            app.dur=app.DurationEditField.Value;    

            if(app.TU==2)
                app.dur=app.dur/1000;
            end


            if(app.npt==1 || app.npt==2)
                if(app.resp_dur==0)
                    warndlg('Set Response Duration > 0 ')
                    return;
                end    
            end

        end
        
        function CloseFigures(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end          
        end  

        
        function Change(app)
        
            app.SelectDataListBox.Visible='off';
            app.OutputArrayNameEditField.Visible='off';
            app.SelectDataListBoxLabel.Visible='off';
            app.OutputArrayNameEditFieldLabel.Visible='off';            
            app.SaveButton.Visible='off';
        
            app.SelectDataListBox_2.Visible='off';
            app.OutputArrayNameEditField_2.Visible='off';
            app.SelectDataListBox_2Label.Visible='off';
            app.OutputArrayNameEditField_2Label.Visible='off';            
            app.SaveButton_2.Visible='off';
            
        
            p=app.UnitsListBox.Value;
            
            if(contains(p,'in'))
                app.iunit=1;
                app.FU='lbf';
                app.VU='in/sec';
                app.DU='in';
                app.MU='lbm';
            else    
                app.iunit=2;    
                app.FU='N';
                app.VU='m/sec';
                app.DU='mm';
                app.MU='kg';
            end

            app.ForcelbfEditFieldLabel.Text=sprintf('Force (%s)',app.FU);
            app.InitialVelocityinsecEditFieldLabel.Text=sprintf('Initial Velocity (%s)',app.VU);                   
            app.InitialDisplacementinEditFieldLabel.Text=sprintf('Initial Displacement (%s)',app.DU);
            app.MasslbmEditFieldLabel.Text=sprintf('Mass (%s)',app.MU);
                
            n=app.PulseTypeListBox.Value;
            
            app.FreqHzEditField.Visible='Off';
            app.FreqHzEditFieldLabel.Visible='Off';            
            

            if(contains(n,'Steady'))
                app.FreqHzEditField.Visible='On';
                app.FreqHzEditFieldLabel.Visible='On';
                app.ResponseDurationsecEditField.Visible='Off';
                app.ResponseDurationsecEditFieldLabel.Visible='Off';                
            else
                app.ResponseDurationsecEditField.Visible='On';
                app.ResponseDurationsecEditFieldLabel.Visible='On';
            end
        end

        function core_core(app)

            app.stiff=(2*pi*app.fn)^2*app.mass;

            app.t=[];
            app.a_th=[];
            app.v_th=[];
            app.d_th=[];
            app.input_force_th=[];
            app.trans_force_th=[];

            if(app.npt==1)
                [app.t,app.a_th,app.v_th,app.d_th,app.input_force_th,app.trans_force_th]=...
                         vibrationdata_half_sine_pulse_force(app.amp,app.dur,app.fn,app.mass,app.stiff,app.damp,app.resp_dur,app.d0,app.v0);
            end
            if(app.npt==2)
                [app.t,app.a_th,app.v_th,app.d_th,app.input_force_th,app.trans_force_th]=...
                       vibrationdata_rectangular_pulse_force(app.amp,app.dur,app.fn,app.mass,app.stiff,app.damp,app.resp_dur,app.d0,app.v0);
            end
            if(app.npt==3)
                f=app.FreqHzEditField.Value;
                [app.t,app.a_th,app.v_th,app.d_th,app.input_force_th,app.trans_force_th]=...
                vibrationdata_sine_pulse_force(app.amp,app.dur,app.fn,app.mass,app.stiff,app.damp,f,app.d0,app.v0);
            end

            if(app.iunit==1)             
               app.a_th=app.a_th/386; 
            else    
               app.a_th=app.a_th/9.81; 
            end  
        end    
        
        function core(app)
            core_core(app);
        end
        function core_srs(app)

            fff=app.fn;

            app.fn=[];
            
            N=length(fff);

            app.a_srs=zeros(N,3);
            app.v_srs=zeros(N,3);
            app.d_srs=zeros(N,3);
            app.tf_srs=zeros(N,3);

            
            for i=1:N

                app.fn=fff(i);

                app.resp_dur=app.dur+1/app.fn;

                core_core(app);

                app.a_srs(i,:)=[app.fn max(app.a_th) abs(min(app.a_th))];
                app.v_srs(i,:)=[app.fn max(app.v_th) abs(min(app.v_th))];
                app.d_srs(i,:)=[app.fn max(app.d_th) abs(min(app.d_th))];
                app.tf_srs(i,:)=[app.fn max(app.trans_force_th) abs(min(app.trans_force_th))];

            end    
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Change(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
               closereq;         
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            CloseFigures(app);
            app.nat=2;

            prep(app);         
            
            core(app);

            sz1=size(app.trans_force_th);
            sz2=size(app.input_force_th);

           
            if(min(sz1)==0 || min(sz2)==0)
                warndlg('ref 2')
                return
            end

            app.t=fix_size(app.t);
            app.a_th=[app.t app.a_th];
            app.v_th=[app.t app.v_th];
            app.d_th=[app.t app.d_th];
            app.trans_force_th=[app.t app.trans_force_th];   
            app.input_force_th=[app.t app.input_force_th];  

            fprintf('\n Response Peaks for case: fn=%g Hz, Q=%g \n\n',app.fn,app.Q);
    
            xlabel2='Time (sec)';
            
            ylabel1=sprintf('Force (%s)',app.FU);
            ylabel2=ylabel1;
            
            data1=app.input_force_th;    
            data2=app.trans_force_th;

            t_string1='Applied Force';
            t_string2=sprintf('Transmitted Force (fn=%g Hz, Q=%g)',app.fn,app.Q);
            
            fig_num=1;
            [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            
            figure(2);
            
            try
                plot(app.a_th(:,1),app.a_th(:,2));
            catch
                warndlg(' Plot failed:  Check input parameters');
                return;
            end
            
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            maxa=max(app.a_th(:,2));
            mina=min(app.a_th(:,2));
            out1=sprintf('Acceleration (fn=%g Hz, Q=%g) \n Peaks (%6.3g G, %6.3g G)',app.fn,app.Q,maxa,mina);
            title(out1);
            fprintf('\n Acceleration:  %6.3g G   %6.3g G \n\n',maxa,mina);
            grid on;

        %    
            figure(3);
            plot(app.v_th(:,1),app.v_th(:,2));
            xlabel('Time (sec)');
            max_v=max(app.v_th(:,2));
            min_v=min(app.v_th(:,2));
            out1=sprintf('Velocity (fn=%g Hz, Q=%g) \n Peaks (%6.3g %s, %6.3g %s)',app.fn,app.Q,max_v,app.VU,min_v,app.VU);            
            ylabel(sprintf('Velocity (%s)',app.VU));        
            title(out1);
            grid on;
        %    
            figure(4);
            plot(app.d_th(:,1),app.d_th(:,2));
            xlabel('Time (sec)');
            max_d=max(app.d_th(:,2));
            min_d=min(app.d_th(:,2));
            out1=sprintf('Displacement (fn=%g Hz, Q=%g) \n Peaks (%6.3g %s, %6.3g %s)',app.fn,app.Q,max_d,app.DU,min_d,app.DU);            
            ylabel(sprintf('Displacement (%s)',app.DU));        
            title(out1);
            grid on;


            app.SelectDataListBox.Visible='on';
            app.OutputArrayNameEditField.Visible='on';
            app.SelectDataListBoxLabel.Visible='on';
            app.OutputArrayNameEditFieldLabel.Visible='on';            
            app.SaveButton.Visible='on';
            
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            CloseFigures(app);
            
            prep(app);
            
            app.fn=[];
            
            app.nat=1;
            
            f1=app.StartFreqHzEditField.Value;
            f2=app.EndFreqHzEditField.Value;
            
            if(f1<=0)
                f1=0.1;
            end

            app.fn(1)=f1;
            i=1;
            while(1)
                app.fn(i+1)=app.fn(i)*2^(1/48);
                if(app.fn(i+1)>f2)
                    app.fn(i+1)=f2;
                    break;
                end
                i=i+1;
                if(i>1e+05)
                    break;
                end
            end   
            
            app.resp_dur=0;
            
            core_srs(app);
          
            fig_num=1;

            size(app.a_srs)
            
            fmin=app.a_srs(1,1);
            fmax=max(app.a_srs(:,1));
            
            md=5;
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            t_string=sprintf('Acceleration SRS Q=%g',app.Q);

            disp('ref 5')
            size(app.a_srs)
            
            ppp1=[app.a_srs(:,1) app.a_srs(:,2)];
            ppp2=[app.a_srs(:,1) app.a_srs(:,3)];

            app.a_srs
            
            leg1='positive';
            leg2='negative';
            
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
                
            t_string=sprintf('Velocity SRS Q=%g',app.Q);
         
            ppp1=[app.v_srs(:,1) app.v_srs(:,2)];
            ppp2=[app.v_srs(:,1) app.v_srs(:,3)];
            
            maxp=max(app.v_srs(:,2));
            maxn=max(app.v_srs(:,3));
            maxpv=max([maxp maxn]);
               
            if(app.iunit==1)
                y_label='Peak Velocity (in/sec)'; 
                fprintf('\n Maximum Velocity = %7.3g in/sec \n',maxpv);
            else
                y_label='Peak Velocity (m/sec)';   
                fprintf('\n Maximum Velocity = %7.3g m/sec \n',maxpv);        
            end
            
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);    
        %       
         
            t_string=sprintf('Displacement SRS Q=%g',app.Q);
          
            ppp1=[app.d_srs(:,1) app.d_srs(:,2)];
            ppp2=[app.d_srs(:,1) app.d_srs(:,3)]; 
               
            if(app.iunit==1)
                y_label='Peak Disp (in)';    
            else
                y_label='Peak Disp (mm)';        
            end   
         
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md); 


            t_string=sprintf('Transmitted Force SRS Q=%g',app.Q);
          
            ppp1=[app.tf_srs(:,1) app.tf_srs(:,2)];
            ppp2=[app.tf_srs(:,1) app.tf_srs(:,3)]; 
               
            if(app.iunit==1)
                y_label='Force (lbf)';    
            else
                y_label='Force (N)';        
            end   
         
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md); 

           
            app.SelectDataListBox_2.Visible='on';
            app.OutputArrayNameEditField_2.Visible='on';
            app.SelectDataListBox_2Label.Visible='on';
            app.OutputArrayNameEditField_2Label.Visible='on';            
            app.SaveButton_2.Visible='on';
            
        end

        % Value changed function: PulseTypeListBox
        function PulseTypeListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            Change(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = strtrim(app.SelectDataListBox.Items);
            im = strtrim(app.SelectDataListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.a_th;
            end  
            if(n==2)
                data=app.v_th;
            end            
            if(n==3)
                data=app.d_th;
            end          
            if(n==4)
                data=app.trans_force_th;
            end            
            if(n==5)
                data=app.input_force_th;


            end   
            
            output_name=app.OutputArrayNameEditField.Value;
            
            assignin('base',output_name,data);
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            d=strtrim(app.SelectDataListBox_2.Value);
            
            if(contains(d,'Acceleration'))
                data=app.a_srs;   
            end
            if(contains(d,'Pseudo'))          
                data=app.pv_srs;                       
            end
            if(contains(d,'Relative'))            
                data=app.rd_srs;                 
            end      
            if(contains(d,'Base'))
                data=app.force_th;   
            end
        
            output_name=app.OutputArrayNameEditField_2.Value;
            
            assignin('base',output_name,data);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ClassicAppliedForceUIFigure = uifigure('Visible', 'off');
            app.ClassicAppliedForceUIFigure.Name = 'Classic Applied Force';
            app.ReturnButton = uibutton(app.ClassicAppliedForceUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel = uilabel(app.ClassicAppliedForceUIFigure);
            app.ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel.Text = 'classical_pulse_applied_force_a   ver 2.0   by Tom Irvine       Laplace Transform Solution ';
            app.TabGroup = uitabgroup(app.ClassicAppliedForceUIFigure);
            app.TimeHistoryResponseTab = uitab(app.TabGroup);
            app.TimeHistoryResponseTab.Title = 'Time History Response';
            app.CalculateButton = uibutton(app.TimeHistoryResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.fnHzEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.fnHzEditFieldLabel.Text = 'fn (Hz)';
            app.fnHzEditField = uieditfield(app.TimeHistoryResponseTab, 'numeric');
            app.ResponseDurationsecEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.ResponseDurationsecEditFieldLabel.Text = 'Response Duration (sec)';
            app.ResponseDurationsecEditField = uieditfield(app.TimeHistoryResponseTab, 'numeric');
            app.SaveButton = uibutton(app.TimeHistoryResponseTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectDataListBoxLabel = uilabel(app.TimeHistoryResponseTab);
            app.SelectDataListBoxLabel.Text = 'Select Data';
            app.SelectDataListBox = uilistbox(app.TimeHistoryResponseTab);
            app.SelectDataListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'Transmitted Force', 'Applied Force'};
            app.SelectDataListBox.Value = 'Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.TimeHistoryResponseTab, 'text');
            app.SRSTab = uitab(app.TabGroup);
            app.SRSTab.Title = 'SRS';
            app.CalculateButton_2 = uibutton(app.SRSTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.SRSTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.SRSTab, 'numeric');
            app.StartFreqHzEditField.Value = 10;
            app.EndFreqHzEditFieldLabel = uilabel(app.SRSTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.SRSTab, 'numeric');
            app.EndFreqHzEditField.Value = 2000;
            app.SaveButton_2 = uibutton(app.SRSTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SelectDataListBox_2Label = uilabel(app.SRSTab);
            app.SelectDataListBox_2Label.Text = 'Select Data';
            app.SelectDataListBox_2 = uilistbox(app.SRSTab);
            app.SelectDataListBox_2.Items = {'Acceleration SRS', 'Velocity SRS', 'Displacement SRS', 'Transmitted Force'};
            app.SelectDataListBox_2.Value = 'Acceleration SRS';
            app.OutputArrayNameEditField_2Label = uilabel(app.SRSTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_2 = uieditfield(app.SRSTab, 'text');
            app.EnterDataPanel = uipanel(app.ClassicAppliedForceUIFigure);
            app.EnterDataPanel.Title = 'Enter Data';
            app.Image = uiimage(app.EnterDataPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.PulseTypeListBoxLabel = uilabel(app.EnterDataPanel);
            app.PulseTypeListBoxLabel.Text = 'Pulse Type';
            app.PulseTypeListBox = uilistbox(app.EnterDataPanel);
            app.PulseTypeListBox.Items = {'Half-Sine', 'Rectangular', 'Steady-State Sine'};
            app.PulseTypeListBox.ValueChangedFcn = createCallbackFcn(app, @PulseTypeListBoxValueChanged, true);
            app.PulseTypeListBox.Value = 'Half-Sine';
            app.UnitsListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.EnterDataPanel);
            app.UnitsListBox.Items = {'lbf, G, in/sec, in', 'N, G , m/sec,  mm'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'lbf, G, in/sec, in';
            app.QEditFieldLabel = uilabel(app.EnterDataPanel);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.QEditField.Value = 10;
            app.ForcelbfEditFieldLabel = uilabel(app.EnterDataPanel);
            app.ForcelbfEditFieldLabel.Text = 'Force (lbf)';
            app.ForcelbfEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.FreqHzEditFieldLabel = uilabel(app.EnterDataPanel);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.EnterDataPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.UnitListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.EnterDataPanel);
            app.UnitListBox.Items = {'sec', 'msec'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'sec';
            app.InitialVelocityinsecEditFieldLabel = uilabel(app.EnterDataPanel);
            app.InitialVelocityinsecEditFieldLabel.Text = 'Initial Velocity (in/sec) ';
            app.InitialVelocityinsecEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.InitialDisplacementinEditFieldLabel = uilabel(app.EnterDataPanel);
            app.InitialDisplacementinEditFieldLabel.Text = 'Initial Displacement (in) ';
            app.InitialDisplacementinEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.MasslbmEditFieldLabel = uilabel(app.EnterDataPanel);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.EnterDataPanel, 'numeric');
            % Show the figure after all components are created
            app.ClassicAppliedForceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classical_pulse_applied_force_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ClassicAppliedForceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ClassicAppliedForceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassicAppliedForceUIFigure)
        end
    end
end