classdef classical_pulse_base_input_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIClassicBaseInputUIFigure     matlab.ui.Figure
        EnterDataPanel                 matlab.ui.container.Panel
        FinalEditField                 matlab.ui.control.NumericEditField
        FinalEditFieldLabel            matlab.ui.control.Label
        InitialEditField               matlab.ui.control.NumericEditField
        InitialEditFieldLabel          matlab.ui.control.Label
        RampDurationsecLabel           matlab.ui.control.Label
        InitialRelativeDisplacementinEditField  matlab.ui.control.NumericEditField
        InitialRelativeDisplacementinEditFieldLabel  matlab.ui.control.Label
        InitialRelativeVelocityinsecEditField  matlab.ui.control.NumericEditField
        InitialRelativeVelocityinsecEditFieldLabel  matlab.ui.control.Label
        UnitListBox                    matlab.ui.control.ListBox
        UnitListBoxLabel               matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        FreqHzEditField                matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel           matlab.ui.control.Label
        AccelGEditField                matlab.ui.control.NumericEditField
        AccelGEditFieldLabel           matlab.ui.control.Label
        QEditField                     matlab.ui.control.NumericEditField
        QEditFieldLabel                matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        PulseTypeListBox               matlab.ui.control.ListBox
        PulseTypeListBoxLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        TimeHistoryResponseTab         matlab.ui.container.Tab
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SelectDataListBox              matlab.ui.control.ListBox
        SelectDataListBoxLabel         matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ResponseDurationsecEditField   matlab.ui.control.NumericEditField
        ResponseDurationsecEditFieldLabel  matlab.ui.control.Label
        fnHzEditField                  matlab.ui.control.NumericEditField
        fnHzEditFieldLabel             matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SRSTab                         matlab.ui.container.Tab
        OutputArrayNameEditField_2     matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        SelectDataListBox_2            matlab.ui.control.ListBox
        SelectDataListBox_2Label       matlab.ui.control.Label
        SaveButton_2                   matlab.ui.control.Button
        EndFreqHzEditField             matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel        matlab.ui.control.Label
        StartFreqHzEditField           matlab.ui.control.NumericEditField
        StartFreqHzEditFieldLabel      matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        fn       %  natural frequency
        Q        %  amplification factor
        nat      %  analysis type
        npt      %  pulse type
        resp_dur %  response duration
        iunit    %  amplitude unit
        a_srs    % 
        pv_srs   % 
        rd_srs   % 
        base_th  % 
        a_th     % 
        rd_th    %     
    end
    
    methods (Access = private)
        
        function CloseFigures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end  

        
        function Change(app)
        
            app.SelectDataListBox.Visible='off';
            app.OutputArrayNameEditField.Visible='off';
            app.SelectDataListBoxLabel.Visible='off';
            app.OutputArrayNameEditFieldLabel.Visible='off';            
            app.SaveButton.Visible='off';
        
            app.SelectDataListBox_2.Visible='off';
            app.OutputArrayNameEditField_2.Visible='off';
            app.SelectDataListBox_2Label.Visible='off';
            app.OutputArrayNameEditField_2Label.Visible='off';            
            app.SaveButton_2.Visible='off';
            
        
            p=app.UnitsListBox.Value;
            
            if(contains(p,'in'))
                app.InitialRelativeDisplacementinEditFieldLabel.Text=...
                    'Initial Relative Displacement (in)';
                app.InitialRelativeVelocityinsecEditFieldLabel.Text=...
                    'Initial Relative Velocity (in/sec)';                
            else    
                app.InitialRelativeDisplacementinEditFieldLabel.Text=...
                    'Initial Relative Displacement (mm)';
                app.InitialRelativeVelocityinsecEditFieldLabel.Text=...
                    'Initial Relative Velocity (m/sec)';                   
            end
        
            m=app.UnitListBox.Value;
            
            if(strcmp(m,'msec'))
                app.RampDurationsecLabel.Text='Ramp Duration (msec)';
            else    
                app.RampDurationsecLabel.Text='Ramp Duration (sec)';            
            end
            
            n=app.PulseTypeListBox.Value;
            
            app.InitialEditField.Visible='Off';
            app.FinalEditField.Visible='Off';
            app.InitialEditFieldLabel.Visible='Off';
            app.FinalEditFieldLabel.Visible='Off'; 
            
            app.RampDurationsecLabel.Visible='Off';
            app.FreqHzEditField.Visible='Off';
            app.FreqHzEditFieldLabel.Visible='Off';            
            
            if(contains(n,'Trapezoidal'))  
                app.RampDurationsecLabel.Visible='On';
                app.InitialEditField.Visible='On';
                app.FinalEditField.Visible='On';
                app.InitialEditFieldLabel.Visible='On';
                app.FinalEditFieldLabel.Visible='On';                
            end
            
            if(contains(n,'Steady'))
                app.FreqHzEditField.Visible='On';
                app.FreqHzEditFieldLabel.Visible='On';
                app.ResponseDurationsecEditField.Visible='Off';
                app.ResponseDurationsecEditFieldLabel.Visible='Off';                
            else
                app.ResponseDurationsecEditField.Visible='On';
                app.ResponseDurationsecEditFieldLabel.Visible='On';
            end
        end
        
        function core(app)
 
            disp('  ');
            disp(' * * * * * * ');
            disp('  ');
            
            app.Q=app.QEditField.Value;            
            app.npt=strtrim(app.PulseTypeListBox.Value);
            
            ntus=app.UnitListBox.Value;

            if(contains(ntus,'msec'))
                ntu=2;
            else
                ntu=1;
            end
            
            iu=app.UnitsListBox.Value;
            
            if(contains(iu,'in'))
                app.iunit=1;
            else
                app.iunit=2;
            end
             
            rd0=app.InitialRelativeDisplacementinEditField.Value;
            rv0=app.InitialRelativeVelocityinsecEditField.Value;
             
            if(app.iunit==2)
               rd0=rd0/1000; 
            end
            
            amp=app.AccelGEditField.Value;
            dur=app.DurationEditField.Value;    

            if(ntu==2)
                dur=dur/1000;
            end
            if(strcmp(app.npt,'Half-Sine'))
                
                app.nat
                size(app.fn)
                app.resp_dur
                
                
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=...
                         vibrationdata_half_sine_pulse_initial(app.nat,amp,dur,app.fn,app.Q,app.resp_dur,app.iunit,rd0,rv0);
            end
            if(strcmp(app.npt,'Rectangular'))
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=vibrationdata_rectangular_pulse_initial(app.nat,amp,dur,app.fn,app.Q,app.resp_dur,app.iunit,rd0,rv0);    
            end
            if(strcmp(app.npt,'Terminal Sawtooth'))
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=...    
                 vibrationdata_terminal_sawtooth_pulse_initial(app.nat,amp,dur,app.fn,app.Q,app.resp_dur,app.iunit,rd0,rv0);      
            end
            if(strcmp(app.npt,'Versed Sine'))
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=...
                       vibrationdata_versed_sine_pulse_initial(app.nat,amp,dur,app.fn,app.Q,app.resp_dur,app.iunit,rd0,rv0);
            end
            if(strcmp(app.npt,'Trapezoidal Pulse'))
                
                d1=app.InitialEditField.Value;
                d2=app.FinalEditField.Value;
                         
                if(ntu==2)
                    d1=d1/1000;
                    d2=d2/1000;        
                end
                
                if(d1<=1e-05)
                    warndlg('Initial ramp duration is too short');
                    return;
                end
                if(d2<=1e-05)
                    warndlg('Final ramp duration is too short');
                    return;
                end    
                
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=...
                       vibrationdata_trapezoidal_pulse_initial(app.nat,app.fn,amp,dur,d1,d2,app.Q,app.resp_dur,app.iunit,rd0,rv0);
            end
            if(strcmp(app.npt,'Steady-State Sine'))            
                  
                app.resp_dur=dur;
             
                frequency=app.FreqHzEditField.Value;
                [app.a_srs,app.pv_srs,app.rd_srs,app.base_th,app.a_th,app.rd_th]=...
                         vibrationdata_sine_initial(app.nat,amp,frequency,app.fn,app.Q,app.resp_dur,app.iunit,rd0,rv0);    
            end    

        
            %

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Change(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
               closereq;         
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            CloseFigures(app);
            app.nat=2;
            app.fn=app.fnHzEditField.Value;
            app.resp_dur=app.ResponseDurationsecEditField.Value;
            
            core(app);
            
            fprintf('\n Response Peaks for case: fn=%g Hz, Q=%g \n\n',app.fn,app.Q);

            figure(1);
            
            try
                plot(app.a_th(:,1),app.a_th(:,2),app.base_th(:,1),app.base_th(:,2));
            catch
                warndlg(' Plot failed:  Check input parameters');
                return;
            end
            
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            maxa=max(app.a_th(:,2));
            mina=min(app.a_th(:,2));
            out1=sprintf('Acceleration (fn=%g Hz, Q=%g) \n Peaks (%6.3g G, %6.3g G)',app.fn,app.Q,maxa,mina);
            title(out1);
            fprintf('\n Acceleration:  %6.3g G   %6.3g G \n\n',maxa,mina);
            grid on;
            legend('Response','Base Input');
            
            fig_num=2;
            
            xlabel2='Time (sec)';
            
            ylabel1='Accel (G)';
            ylabel2='Accel (G)';
            
            data1=app.base_th;    
            data2=app.a_th;
         
            t_string1='Base Acceleration';
            t_string2=sprintf('Response Acceleration (fn=%g Hz, Q=%g)',app.fn,app.Q);
            
            [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            
        %    
            figure(3);
            plot(app.rd_th(:,1),app.rd_th(:,2));
            xlabel('Time (sec)');
            max_rd=max(app.rd_th(:,2));
            min_rd=min(app.rd_th(:,2));
            if(app.iunit==1)
                out1=sprintf('Relative Displacement (fn=%g Hz, Q=%g) \n Peaks (%6.3g in, %6.3g in)',app.fn,app.Q,max_rd,min_rd);        
                out2=sprintf('Relative Displacement:  %6.3g in   %6.3g in',max_rd,min_rd);    
                ylabel('Rel Disp (in)');        
            else
                out1=sprintf('Relative Displacement (fn=%g Hz, Q=%g) \n Peaks (%6.3g mm, %6.3g mm)',app.fn,app.Q,max_rd,min_rd);        
                out2=sprintf('Relative Displacement:  %6.3g mm   %6.3g mm',max_rd,min_rd);
                ylabel('Rel Disp (mm)');         
            end
            title(out1);
            disp(out2);
            grid on;
            
            app.SelectDataListBox.Visible='on';
            app.OutputArrayNameEditField.Visible='on';
            app.SelectDataListBoxLabel.Visible='on';
            app.OutputArrayNameEditFieldLabel.Visible='on';            
            app.SaveButton.Visible='on';
            
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            CloseFigures(app);
            
            app.fn=[];
            
            app.nat=1;
            
            f1=app.StartFreqHzEditField.Value;
            f2=app.EndFreqHzEditField.Value;
            
            if(f1<=0)
                f1=0.1;
            end

            app.fn(1)=f1;
            i=1;
            while(1)
                app.fn(i+1)=app.fn(i)*2^(1/48);
                if(app.fn(i+1)>f2)
                    app.fn(i+1)=f2;
                    break;
                end
                i=i+1;
                if(i>1e+05)
                    break;
                end
            end   
            
            app.resp_dur=0;
            
            core(app);
          
            fig_num=1;
            
            fmin=app.a_srs(1,1);
            fmax=max(app.a_srs(:,1));
            
            md=5;
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            t_string=sprintf('Acceleration SRS Q=%g',app.Q);
            
            ppp1=[app.a_srs(:,1) app.a_srs(:,2)];
            ppp2=[app.a_srs(:,1) app.a_srs(:,3)];
            
            leg1='positive';
            leg2='negative';
            
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
                
            t_string=sprintf('Pseudo Velocity SRS Q=%g',app.Q);
         
            ppp1=[app.pv_srs(:,1) app.pv_srs(:,2)];
            ppp2=[app.pv_srs(:,1) app.pv_srs(:,3)];
            
            maxp=max(app.pv_srs(:,2));
            maxn=max(app.pv_srs(:,3));
            maxpv=max([maxp maxn]);
               
            if(app.iunit==1)
                y_label='Peak Velocity (in/sec)'; 
                fprintf('\n Maximum Pseudo Velocity = %7.3g in/sec \n',maxpv);
            else
                y_label='Peak Velocity (m/sec)';   
                fprintf('\n Maximum Pseudo Velocity = %7.3g m/sec \n',maxpv);        
            end
            
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);    
        %       
         
            t_string=sprintf('Relative Displacement SRS Q=%g',app.Q);
          
            ppp1=[app.rd_srs(:,1) app.rd_srs(:,2)];
            ppp2=[app.rd_srs(:,1) app.rd_srs(:,3)]; 
               
            if(app.iunit==1)
                y_label='Peak Rel Disp (in)';    
            else
                y_label='Peak Rel Disp (mm)';        
            end   
         
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md); 
           
            app.SelectDataListBox_2.Visible='on';
            app.OutputArrayNameEditField_2.Visible='on';
            app.SelectDataListBox_2Label.Visible='on';
            app.OutputArrayNameEditField_2Label.Visible='on';            
            app.SaveButton_2.Visible='on';
            
        end

        % Value changed function: PulseTypeListBox
        function PulseTypeListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            Change(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            d=strtrim(app.SelectDataListBox.Value);
            
            if(contains(d,'Base'))
                data=app.base_th;   
            end
            if(contains(d,'Response'))          
                data=app.a_th;                       
            end
            if(contains(d,'Relative'))            
                data=app.rd_th;                 
            end            
        
            output_name=app.OutputArrayNameEditField.Value;
            
            assignin('base',output_name,data);

            msgbox('Array saved');
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            d=strtrim(app.SelectDataListBox_2.Value);
            
            if(contains(d,'Acceleration'))
                data=app.a_srs;   
            end
            if(contains(d,'Pseudo'))          
                data=app.pv_srs;                       
            end
            if(contains(d,'Relative'))            
                data=app.rd_srs;                 
            end      
            if(contains(d,'Base'))
                data=app.base_th;   
            end
        
            output_name=app.OutputArrayNameEditField_2.Value;
            
            assignin('base',output_name,data);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIClassicBaseInputUIFigure = uifigure('Visible', 'off');
            app.UIClassicBaseInputUIFigure.Name = 'UI Classic Base Input';
            app.ReturnButton = uibutton(app.UIClassicBaseInputUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel = uilabel(app.UIClassicBaseInputUIFigure);
            app.ClassicalBaseInputver19byTomIrvineLaplaceTransformSolutionLabel.Text = 'classical_pulse_base_input_a   ver 2.1   by Tom Irvine       Laplace Transform Solution ';
            app.TabGroup = uitabgroup(app.UIClassicBaseInputUIFigure);
            app.TimeHistoryResponseTab = uitab(app.TabGroup);
            app.TimeHistoryResponseTab.Title = 'Time History Response';
            app.CalculateButton = uibutton(app.TimeHistoryResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.fnHzEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.fnHzEditFieldLabel.Text = 'fn (Hz)';
            app.fnHzEditField = uieditfield(app.TimeHistoryResponseTab, 'numeric');
            app.ResponseDurationsecEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.ResponseDurationsecEditFieldLabel.Text = 'Response Duration (sec)';
            app.ResponseDurationsecEditField = uieditfield(app.TimeHistoryResponseTab, 'numeric');
            app.SaveButton = uibutton(app.TimeHistoryResponseTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectDataListBoxLabel = uilabel(app.TimeHistoryResponseTab);
            app.SelectDataListBoxLabel.Text = 'Select Data';
            app.SelectDataListBox = uilistbox(app.TimeHistoryResponseTab);
            app.SelectDataListBox.Items = {'Base Acceleration', 'Response Acceleration', 'Relative Displacement'};
            app.SelectDataListBox.Value = 'Base Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.TimeHistoryResponseTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.TimeHistoryResponseTab, 'text');
            app.SRSTab = uitab(app.TabGroup);
            app.SRSTab.Title = 'SRS';
            app.CalculateButton_2 = uibutton(app.SRSTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.SRSTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.SRSTab, 'numeric');
            app.StartFreqHzEditField.Value = 10;
            app.EndFreqHzEditFieldLabel = uilabel(app.SRSTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.SRSTab, 'numeric');
            app.EndFreqHzEditField.Value = 2000;
            app.SaveButton_2 = uibutton(app.SRSTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SelectDataListBox_2Label = uilabel(app.SRSTab);
            app.SelectDataListBox_2Label.Text = 'Select Data';
            app.SelectDataListBox_2 = uilistbox(app.SRSTab);
            app.SelectDataListBox_2.Items = {'Acceleration SRS', 'Pseudo Velocity SRS', 'Relative Disp SRS', 'Base Time History'};
            app.SelectDataListBox_2.Value = 'Acceleration SRS';
            app.OutputArrayNameEditField_2Label = uilabel(app.SRSTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_2 = uieditfield(app.SRSTab, 'text');
            app.EnterDataPanel = uipanel(app.UIClassicBaseInputUIFigure);
            app.EnterDataPanel.Title = 'Enter Data';
            app.Image = uiimage(app.EnterDataPanel);
            app.Image.ImageSource = 'sdof_base.jpg';
            app.PulseTypeListBoxLabel = uilabel(app.EnterDataPanel);
            app.PulseTypeListBoxLabel.Text = 'Pulse Type';
            app.PulseTypeListBox = uilistbox(app.EnterDataPanel);
            app.PulseTypeListBox.Items = {'Half-Sine', 'Rectangular', 'Terminal Sawtooth', 'Versed Sine', 'Trapezoidal Pulse', 'Steady-State Sine'};
            app.PulseTypeListBox.ValueChangedFcn = createCallbackFcn(app, @PulseTypeListBoxValueChanged, true);
            app.PulseTypeListBox.Value = 'Half-Sine';
            app.UnitsListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.EnterDataPanel);
            app.UnitsListBox.Items = {'G  in/sec  in', 'G  m/sec  mm'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G  in/sec  in';
            app.QEditFieldLabel = uilabel(app.EnterDataPanel);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.QEditField.Value = 10;
            app.AccelGEditFieldLabel = uilabel(app.EnterDataPanel);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.FreqHzEditFieldLabel = uilabel(app.EnterDataPanel);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.EnterDataPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.UnitListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.EnterDataPanel);
            app.UnitListBox.Items = {'sec', 'msec'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'sec';
            app.InitialRelativeVelocityinsecEditFieldLabel = uilabel(app.EnterDataPanel);
            app.InitialRelativeVelocityinsecEditFieldLabel.Text = 'Initial Relative Velocity (in/sec) ';
            app.InitialRelativeVelocityinsecEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.InitialRelativeDisplacementinEditFieldLabel = uilabel(app.EnterDataPanel);
            app.InitialRelativeDisplacementinEditFieldLabel.Text = 'Initial Relative Displacement (in) ';
            app.InitialRelativeDisplacementinEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.RampDurationsecLabel = uilabel(app.EnterDataPanel);
            app.RampDurationsecLabel.Text = 'Ramp Duration (sec)';
            app.InitialEditFieldLabel = uilabel(app.EnterDataPanel);
            app.InitialEditFieldLabel.Text = 'Initial ';
            app.InitialEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.FinalEditFieldLabel = uilabel(app.EnterDataPanel);
            app.FinalEditFieldLabel.Text = 'Final';
            app.FinalEditField = uieditfield(app.EnterDataPanel, 'numeric');
            % Show the figure after all components are created
            app.UIClassicBaseInputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classical_pulse_base_input_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIClassicBaseInputUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIClassicBaseInputUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIClassicBaseInputUIFigure)
        end
    end
end