classdef cpsd_H1_H2_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CPSDH1H2UIFigure               matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        DataEntryTab                   matlab.ui.container.Tab
        ResponseListBox                matlab.ui.control.ListBox
        ResponseListBoxLabel           matlab.ui.control.Label
        InputListBox                   matlab.ui.control.ListBox
        InputListBoxLabel              matlab.ui.control.Label
        SelectResponseInputArrayListBox  matlab.ui.control.ListBox
        SelectResponseInputArrayListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1_2                        matlab.ui.control.Label
        ListboxFormat                  matlab.ui.control.ListBox
        text1                          matlab.ui.control.Label
        ListboxMethod                  matlab.ui.control.ListBox
        ApplyTimeLimitsButton          matlab.ui.control.Button
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        ReadDataButton                 matlab.ui.control.Button
        CPSDH1H2Tab                    matlab.ui.container.Tab
        OutputLabel                    matlab.ui.control.Label
        H1H2CheckBox                   matlab.ui.control.CheckBox
        CPSDCheckBox                   matlab.ui.control.CheckBox
        HanningisbestchoiceforCPSDH1H2Label  matlab.ui.control.Label
        PeakResultsEditField           matlab.ui.control.EditField
        PeakResultsEditFieldLabel      matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        WindowListBox                  matlab.ui.control.ListBox
        WindowListBoxLabel             matlab.ui.control.Label
        MeanRemovalListBox             matlab.ui.control.ListBox
        MeanRemovalListBoxLabel        matlab.ui.control.Label
        ProcessingOptionsLabel         matlab.ui.control.Label
        SelectdofListBox               matlab.ui.control.ListBox
        SelectdofListBoxLabel          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CalculateButton                matlab.ui.control.Button
        ExportFunctionsTab             matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        FunctionListBox                matlab.ui.control.ListBox
        FunctionListBoxLabel           matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        H1H2ModalCurvefitTab           matlab.ui.container.Tab
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        H2FRFInputArrayNameEditField   matlab.ui.control.EditField
        H2FRFInputArrayNameEditFieldLabel  matlab.ui.control.Label
        H1FRFInputArrayNameEditField   matlab.ui.control.EditField
        H1FRFInputArrayNameEditFieldLabel  matlab.ui.control.Label
        CalculateButton_modal          matlab.ui.control.Button
        MaxEditField_modal             matlab.ui.control.EditField
        MaxEditField_2Label            matlab.ui.control.Label
        MinEditField_modal             matlab.ui.control.EditField
        MinEditField_2Label            matlab.ui.control.Label
        FrequenciesHzLabel             matlab.ui.control.Label
        MethodListBox                  matlab.ui.control.ListBox
        MethodListBoxLabel             matlab.ui.control.Label
        FunctionListBox_modal          matlab.ui.control.ListBox
        FunctionListBox_2Label         matlab.ui.control.Label
        NumberofModesListBox           matlab.ui.control.ListBox
        NumberofModesListBoxLabel      matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM     % time history array
        ttt     % time column
        amp     % amplitude column
        s1      % amplitude dimension
        s2      % amplitude unitf
        dt      % time step
        df      % frequency step
        num     % number of points 
        nhalf   % half the number of points
        AA      % signal processing option array
        fig_num % figure number 
        Fourier_magnitude       % 
        power_spectral_density  % 
        fmin %
        fmax %
        rms  %
        x_label %
        y_label %
        N %
        iflag %
        ylabel1 %
        ylabel2 %
        psd_y1  %
        psd_y2  %
        YS_input     %
        YS_response  %
        trans_ylabel %
        a_power_spectral_density %
        b_power_spectral_density %
        power_trans  %
        iout    %
        cpsd    %
        au      %
        bu      %
        an      %
        bn      %
        iau     %
        ibu     %

        H1_m_store
        H1_mp_store
        H1_complex_store        
        H2_m_store
        H2_mp_store
        H2_complex_store
        COH_store

    end
    
    methods (Access = private)

        function external_array(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end

        end    

        function TH_plot(app)
 
            xlabel2='Time (sec)';
            t_string1='Input';
            t_string2='Response';
            data1=[app.THM(:,1) app.THM(:,2)];
            data2=[app.THM(:,1) app.THM(:,3)];

            app.ylabel1=sprintf('%s (%s)',app.an,app.au);
            app.ylabel2=sprintf('%s (%s)',app.bn,app.bu);

            if(contains(app.ylabel1,app.ylabel2))
                [app.fig_num]=subplots_two_linlin_two_titles_scale(app.fig_num,xlabel2,app.ylabel1,app.ylabel2,data1,data2,t_string1,t_string2);
            else 
                [app.fig_num]=subplots_two_linlin_two_titles(app.fig_num,xlabel2,app.ylabel1,app.ylabel2,data1,data2,t_string1,t_string2);
            end
        end    

        function Choice(app)

            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';

            app.num=length(app.THM(:,1));
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.THM(:,2);
            app.ttt=app.THM(:,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            app.N=floor(log(app.num)/log(2));
            
            if(app.N>12)
                app.N=12;
            end
            
            ss={'2','4','8','16','32','64','128','256','512','1024','2048','4096'};            
            sq={'1','2','3', '4', '5', '6',  '7',  '8',  '9',  '10',  '11', '12'};            
             
            A=zeros(app.N,5);
            
            for i=1:app.N
                m=2^(i-1); 
                A(i,1)=m;      
                A(i,2)=floor(app.num/m);
                A(i,3)=A(i,2)*app.dt;
                A(i,4)=1/A(i,3);
                A(i,5)=2*A(i,1);
            end  
            
            myData=array2table(A);
            app.UITable.Data=myData;  
            
            for i=10:-1:app.N
                ss(end)=[];
                sq(end)=[];                
            end
  
            app.SelectdofListBox.Items=ss;
            % app.UITable.RowName=sq;
            
            app.AA=A;
        end            

       function Trans_change(app)

            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            app.SelectInputArrayListBox.Visible='off';
            app.SelectInputArrayListBoxLabel.Visible='off';   
            app.SelectResponseInputArrayListBox.Visible='off';
            app.SelectResponseInputArrayListBoxLabel.Visible='off';  

            if(n==2)
                external_array(app);
            end    

            app.SelectInputArrayListBox.Visible='on';
            app.SelectInputArrayListBoxLabel.Visible='on'; 
                
            if(m==1)
                ss=sprintf('Select Common Input Array');
            else
                ss=sprintf('Select Input Array');
                app.SelectResponseInputArrayListBox.Visible='on';
                app.SelectResponseInputArrayListBoxLabel.Visible='on';                
            end

            app.SelectInputArrayListBoxLabel.Text=ss; 
            update(app);

            app.ListboxMethod.Value=app.ListboxMethod.Items{1};

        end        
        
        function Signal(app)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
                app.ApplyTimeLimitsButton.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.ApplyTimeLimitsButton.Visible='on';         
            end
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            cell_array=update_time_history_23();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.SelectResponseInputArrayListBox.Items=unique(sort(cell_array));             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Signal(app);
            update(app);
            Trans_change(app);

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            ClosePlots(app);

            app.fig_num=1;
            

            Items = strtrim(app.InputListBox.Items);
            im = strtrim(app.InputListBox.Value);
            app.iau = find(strcmp(Items, im));

            Items = strtrim(app.ResponseListBox.Items);
            im = strtrim(app.ResponseListBox.Value);
            app.ibu = find(strcmp(Items, im));
            
            if(app.iau==1)
                app.an='Accel';
                app.au='G';
            end    
            if(app.iau==2)
                app.an='Force';
                app.au='lbf';
            end  
            if(app.iau==3)
                app.an='Force';
                app.au='N';
            end                          
            if(app.iau==4)
                app.an='Pressure';
                app.au='psi';
            end  
            if(app.iau==5)
                app.an='Pressure';
                app.au='Pa';
            end  

            if(app.ibu==1)
                app.bn='Accel';
                app.bu='G';
            end 
            if(app.ibu==2)
                app.bn='Velocity';
                app.bu='in/sec';
            end 
            if(app.ibu==3)
                app.bn='Velocity';
                app.bu='m/sec';
            end 
            if(app.ibu==4)
                app.bn='Displacement';
                app.bu='in';
            end            
            if(app.ibu==5)
                app.bn='Displacement';
                app.bu='m';
            end 
            if(app.ibu==6)
                app.bn='Displacement';
                app.bu='mm';
            end 
            if(app.ibu==7)
                app.bn='Pressure';
                app.bu='psi';
            end 
            if(app.ibu==8)
                app.bn='Pressure';
                app.bu='Pa';
            end        

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            if(n==1)

                if(m==1)
                    try
                        FS=app.SelectInputArrayListBox.Value;
                        app.THM=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end
                else

                    try
                        FS=app.SelectInputArrayListBox.Value;
                        F1=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end

                    try
                        FS=app.SelectResponseInputArrayListBox.Value;
                        F2=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end                    
   
                    try
                        app.THM=[F1 F2(:,2)];
                    catch
                        size(F1)
                        size(F2)
                        warndlg('Arrays not compatible');
                        return;
                    end    

                end

            else
                if(m==1)
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       app.THM = fscanf(fid,'%g %g %g',[2 inf]);
                       app.THM=app.THM';
                
                       size(app.THM)
    
                       msgbox('File read');              
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end
                else    
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F1 = fscanf(fid,'%g %g',[2 inf]);
                       F1=F1';
                
                       sz1=size(F1);
    
                       msgbox('File 1 read'); 

                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F2 = fscanf(fid,'%g %g',[2 inf]);
                       F2=F2';
                
                       sz2=size(F2);
    
                       msgbox('File 2 read');

                       if(sz1(1)~=sz2(1))
                            warndlg('File 1 & File 2 have different lengths')
                            return;
                       else    
                            app.THM=[F1 F2(:,2)];
                       end     
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end                
                end
            end           

            try
                TH_plot(app);
            catch
                warndlg('Time history plot failed');
                return;
            end

            Choice(app);

            msgbox('Apply Time Limits Or Go to CPSD,H1,H1 tab');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            ClosePlots(app);

            if(app.iflag==999)
                return;
            end


            fprintf('\n\n * * * * * * * * \n\n');
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end
            if(max(size(app.THM))<=1)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end           
            if(app.N==0)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end               
        
            b=app.SelectdofListBox.Value;
            for i=1:app.N
                a=2^i;
                ss=sprintf('%d',a);
                
                if(strcmp(b,ss))
                    % dof=a;
                    num_seg=app.AA(i,1);
                    samples_seg=app.AA(i,2);
                    app.df=app.AA(i,4);
                    break;
                end
            end  
            
            NW=num_seg;
            
            mmm=samples_seg;

            mH=floor(((mmm/2)-1));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            mmr=app.MeanRemovalListBox.Value;
            
            if(strcmp(mmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            h_choice = find(strcmp(Items, im));

            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ylab1=sprintf('%s (%s^2/Hz)',app.an,app.au);
            ylab2=sprintf('%s (%s^2/Hz)',app.bn,app.bu);

            a=app.THM(:,2);
            b=app.THM(:,3);
     
            %%%%%%%%%%%%%%%%%%%

            [freq,COH,CPSD,PSD_A,PSD_B,H1,H2,H1_m,H1_p,H1_real,H1_imag,H2_m,H2_p,H2_real,H2_imag]=...
                                        CPSD_function(a,b,NW,mmm,mH,mr_choice,h_choice,app.df);

            ff=fix_size(freq);    
            fz=length(ff);
            LF=length(ff);

            md=6;    
            xlab='Frequency (Hz)';

            if(app.CPSDCheckBox.Value==1)
                
                CPSD_mag=abs(CPSD);
                CPSD_phase=(180/pi)*atan2(imag(CPSD),real(CPSD));

                CPSD_phase=CPSD_phase(1:fz);
                CPSD_mag=CPSD_mag(1:fz);
                
                [~,app.rms] = calculate_PSD_slopes_fmin_fmax(ff,CPSD_mag,app.fmin,app.fmax);
                [~,rms1] = calculate_PSD_slopes_fmin_fmax(ff,PSD_A,app.fmin,app.fmax);
                [~,rms2] = calculate_PSD_slopes_fmin_fmax(ff,PSD_B,app.fmin,app.fmax);    
                %   
                     
                fprintf('\n CPSD Overall RMS = %10.3g \n',app.rms);
                fprintf(' CPSD Three Sigma = %10.3g \n\n',3*app.rms);
                %
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                t_string=sprintf(' PSD  Signal 1  %6.3g %s RMS Overall ',rms1,app.au); 
                app.a_power_spectral_density=[ff PSD_A(1:LF)];
                [app.fig_num]=plot_loglog_function_md(app.fig_num,xlab,ylab1,t_string,app.a_power_spectral_density,app.fmin,app.fmax,md);
                
                t_string=sprintf(' PSD  Signal 2  %6.3g %s RMS Overall ',rms2,app.bu); 
                app.b_power_spectral_density=[ff PSD_B(1:LF)];
                [app.fig_num]=plot_loglog_function_md(app.fig_num,xlab,ylab2,t_string,app.b_power_spectral_density,app.fmin,app.fmax,md);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                   if(app.iau==1 && app.ibu==1)
                        t_string=sprintf('Cross Power Spectral Density %6.3g GRMS Overall ',app.rms);
                        yout=sprintf('Accel (G^2/Hz)');
                   else 
                        t_string=sprintf('Cross Power Spectral Density %6.3g rms Overall ',app.rms);  
                        yout=sprintf('Magnitude [(%s %s)/Hz]',app.au,app.bu); 
                   end
                   
                   
                   [app.fig_num]=plot_CPSD(app.fig_num,t_string,ff,CPSD_mag,CPSD_phase,COH,app.fmin,app.fmax,yout);  
                %
                   [~,fcc]=find_max([ff CPSD_mag]);
                %
                   fprintf('\n Peak occurs at %10.5g Hz \n',fcc);
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                R = corrcoef(a,b);
                rho=R(1,2);
                
                fprintf('\n Pearson correlation coefficent = %8.4g \n\n',rho);
                
                app.cpsd=[ff CPSD_mag CPSD_phase];

            end
            if(app.H1H2CheckBox.Value==1)

                FL=length(freq);
                H1=H1(1:FL,1);
                H2=H2(1:FL,1);
    

                app.H1_m_store=[freq H1_m];
                app.H1_mp_store=[freq H1_m H1_p];
                app.H1_complex_store=[freq H1];

                app.H2_m_store=[freq H2_m];
                app.H2_mp_store=[freq H2_m H2_p];
                app.H2_complex_store=[freq H2];

                app.COH_store=[freq COH];

                %
                tmp = abs(freq-app.fmin);
                [~,idx1] = min(tmp);     
                
                tmp = abs(freq-app.fmax);
                [~,idx2] = min(tmp);
                
                
                [xtt,xTT,app.iflag]=xtick_label(app.fmin,app.fmax);    
                
                if(app.iflag==1)
                    app.fmin=min(xtt);
                    app.fmax=max(xtt);             
                end

                %
                   t_string=sprintf('H1 Frequency Response Function ');
                %
                    hp=figure(app.fig_num);
                    app.fig_num=app.fig_num+1;
                %
                    subplot(3,1,1);
                    plot(freq,H1_p);
                    
                    title(t_string);
                    grid on;
                    ylabel('Phase (deg)');      
                    
                    if(app.iflag==1)
                        set(gca,'xtick',xtt);
                        set(gca,'XTickLabel',xTT);
                    end
                    axis([app.fmin,app.fmax,-180,180]);
                        set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin','ytick',[-180,-90,0,90,180]);    
                %
                    subplot(3,1,[2 3]);
                    plot(freq,H1_m);
                    grid on;
                    xlabel('Frequency(Hz)');
                    out2=sprintf('Magnitude (%s/%s)',app.bu,app.au);
                    ylabel(out2);
                    set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log',...
                         'YScale','log');
                %
                    ymax=10^(ceil(log10(max(H1_m(idx1:idx2)))));
                    ymin=10^(floor(log10(min(H1_m(idx1:idx2)))));
                %
                    if(ymin<ymax/10000)
                        ymin=ymax/10000;
                    end
            %
                
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end
                axis([app.fmin,app.fmax,ymin,ymax]);
                set(hp, 'Position', [0 0 600 550]);      
            %
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

               t_string=sprintf('H2 Frequency Response Function ');
            %
                hp=figure(app.fig_num);
                app.fig_num=app.fig_num+1;
            %
                subplot(3,1,1);
                plot(freq,H2_p);
                title(t_string);
                grid on;
                ylabel('Phase (deg)');
            
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end    
                axis([app.fmin,app.fmax,-180,180]);
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin','ytick',[-180,-90,0,90,180]);
            %
                subplot(3,1,[2 3]);
                plot(freq,H2_m);
                grid on;
                xlabel('Frequency(Hz)');
                ylabel(out2);
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log',...
                     'YScale','log');
            %
                ymax=10^(ceil(log10(max(H2_m(idx1:idx2)))));
                ymin=10^(floor(log10(min(H2_m(idx1:idx2)))));
            %
                if(ymin<ymax/10000)
                    ymin=ymax/10000;
                end
            %
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end
                axis([app.fmin,app.fmax,ymin,ymax]); 
                set(hp, 'Position', [0 0 600 550]);
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                
                plot(freq,COH);
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log',...
                     'YScale','lin');
                xlabel('Frequency(Hz)'); 
                ylabel('(\gamma_x_y)^2'); 
                title('Coherence');   
                ymin=0.;
                ymax=1.;
    
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end    
                axis([app.fmin,app.fmax,ymin,ymax]);

            %
            
                [~,ffmax]=find_max([freq(idx1:idx2) H1_m(idx1:idx2)]);
            %
                out5 = sprintf('\n H1 Peak occurs at %10.5g Hz within plot limits',ffmax);
                disp(out5)
            %

            end  


            if(app.CPSDCheckBox.Value==1 )
                string_th{1}=sprintf('PSD Input');
                string_th{end+1}=sprintf('PSD Response');
                string_th{end+1}=sprintf('CPSD');
                
                if(app.H1H2CheckBox.Value==1)
                    string_th{end+1}=sprintf('H1: Frequency & Magnitude');
                    string_th{end+1}=sprintf('H1: Frequency & Magnitude & Phase');
                    string_th{end+1}=sprintf('H1: Frequency & Complex');
                    string_th{end+1}=sprintf('H2: Frequency & Magnitude');
                    string_th{end+1}=sprintf('H2: Frequency & Magnitude & Phase');
                    string_th{end+1}=sprintf('H2: Frequency & Complex');
                    string_th{end+1}=sprintf('Coherence');
                end

            else    
                if(app.H1H2CheckBox.Value==1)
                    string_th{1}=sprintf('H1: Frequency & Magnitude');
                    string_th{end+1}=sprintf('H1: Frequency & Magnitude & Phase');
                    string_th{end+1}=sprintf('H1: Frequency & Complex');
                    string_th{end+1}=sprintf('H2: Frequency & Magnitude');
                    string_th{end+1}=sprintf('H2: Frequency & Magnitude & Phase');
                    string_th{end+1}=sprintf('H2: Frequency & Complex');
                    string_th{end+1}=sprintf('Coherence');
                end             
                
            end    

            if(app.H1H2CheckBox==1)
               
                assignin('base','H1_complex', [freq H1]);
                assignin('base','H2_complex', [freq H2]);
                msgbox(' Saved Arrays: H1_complex, H2_complex');
            end

            app.FunctionListBox.Items=string_th;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                   
            Items = char(app.FunctionListBox.Items);
            im=app.FunctionListBox.Value;
            [n]=find_index(Items,im); 

                if(n==1)
                    data=app.a_power_spectral_density;
                end
                if(n==2)
                    data=app.b_power_spectral_density;
                end
                if(n==3)
                    data=app.cpsd;
                end    
                if(n==4)
                    data=app.H1_m_store;
                end    
                if(n==5)
                    data=app.H1_mp_store;
                end 
                if(n==6)
                    data=app.H1_complex_store; 
                end               
                if(n==7)
                    data=app.H2_m_store;
                end 
                if(n==8)
                    data=app.H2_mp_store;
                end 
                if(n==9)
                    data=app.H2_complex_store;
                end               
                if(n==10)
                    data=app.COH_store;
                end 
             
            try
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name, data); 

                msgbox('Save Complete');
            catch
                warndlg('Save Failed');
            end
            
        end

        % Callback function
        function PlotButtonPushed(app, event)

            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end
        
            FS = app.InputSpecificationArrayNameEditField.Value;
            try
                STHM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end  
        
            [~,srms] = calculate_PSD_slopes_no(STHM(:,1),STHM(:,2));

            ppp=app.power_spectral_density;
            qqq=STHM; 
            
   
            leg_b=sprintf('input %6.3g %sRMS',app.rms,app.s2);
            leg_a=sprintf('spec  %6.3g %sRMS',srms,app.s2);
            
            t_string='Power Spectral Density';
            
            pname='';
            nps=2;

            [app.fig_num]=plot_PSD_two_f(app.fig_num,app.x_label,app.y_label,t_string,qqq,ppp,leg_a,leg_b,app.fmin,app.fmax,pname,nps);
                
        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
        
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button down function: CPSDH1H2Tab
        function CPSDH1H2TabButtonDown(app, event)
            if(app.N>=1)
            else    
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end  
        end

        % Callback function
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Callback function
        function listbox_methodValueChanged(app, event)

            update(app);
            
        end

        % Button pushed function: ApplyTimeLimitsButton
        function ApplyTimeLimitsButtonPushed(app, event)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Segment'))
                tstart=app.StartTimesecEditField.Value;
                tend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);    

                TH_plot(app);

                msgbox('Go to Calculate Trans tab');
            end
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed2(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxMethod
        function ListboxMethodValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat
        function ListboxFormatValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: SelectdofListBox
        function SelectdofListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Value changed function: MeanRemovalListBox
        function MeanRemovalListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Callback function
        function ModalCurvefitButtonPushed(app, event)
            handles.s=modal_curve_fit;
            set(handles.s,'Visible','on'); 
        end

        % Button pushed function: CalculateButton_modal
        function CalculateButton_modalPushed(app, event)
            app.fig_num=10;

            disp(' ');
            disp(' * * * * * * * ');
            disp(' * * * * * * * ');
            disp(' ');
  
            
            app.fmin=str2num(app.MinEditField_modal.Value);
            app.fmax=str2num(app.MaxEditField_modal.Value);

            try
                FS1=app.H1FRFInputArrayNameEditField.Value;
                H1=evalin('base',FS1);   
            catch
                warndlg('Enter H1 FRF input array name');
                return;    
            end
            try
                FS2=app.H2FRFInputArrayNameEditField.Value;
                H2=evalin('base',FS2);   
            catch
            end

            fs=1/app.dt;
            
            mnum=str2num(app.NumberofModesListBox.Value);

            Items = strtrim(app.FunctionListBox_modal.Items);
            im = strtrim(app.FunctionListBox_modal.Value);
            m = find(strcmp(Items, im));

            f=H1(:,1);
            
            if(m==1)
                frf=H1(:,2:end);
                disp(' H1 ');
                tts=sprintf('H1  Receptance');
            else
                frf=H2(:,2:end);
                disp(' H2 ');
                tts=sprintf('H2  Receptance');    
            end
            
            [~,ii]=min(abs(f-app.fmin));
            [~,jj]=min(abs(f-app.fmax));
            
            original_frf=frf;
            original_f=f;
            
            frf=frf(ii:jj,:);
            f=f(ii:jj);
            
            Items = strtrim(app.MethodListBox.Items);
            im = strtrim(app.MethodListBox.Value);
            nn = find(strcmp(Items, im));

            if(nn==2 || nn==3)
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
            end
            
            if(nn==1)
                [fn,dr,ms,ofrf] = modalfit(frf,f,fs,mnum,'FreqRange',[f(1) f(end)],'FitMethod','pp');
                disp(' Peak-Picking Method');
            end
            if(nn==2)
                disp(' Least-Squares Rational Function Estimation ');
                disp(' Stability Diagram Results'); 
                [fn,dr,ms,ofrf] = modalfit(frf,f,fs,mnum,'FreqRange',[f(1) f(end)],'FitMethod','lsrf');    
                fnx=modalsd(frf,f,fs,'MaxModes',mnum,'FreqRange',[f(1) f(end)],'FitMethod','lsrf');
                fnx
            end
            if(nn==3)
                disp(' Least-Squares Complex Exponential Method ');
                disp(' Stability Diagram Results'); 
                [fn,dr,ms,ofrf] = modalfit(original_frf,original_f,fs,mnum,'FitMethod','lsce','FreqRange',[f(1) f(end)]);     
            
                [~,ii]=min(abs(original_f-app.fmin));
                [~,jj]=min(abs(original_f-app.fmax));
                ofrf=ofrf(ii:jj,:);
            
                fnx=modalsd(original_frf,original_f,fs,'MaxModes',mnum,'FreqRange',[f(1) f(end)]);
                fnx 
            end


            if(nn>=2)
                k=1;
                sz=size(fnx);

                fns=zeros(sz(2),1);

                for j=1:sz(2)
                    for i=sz(1):-1:1
                        if(fnx(i,j)>0 && fnx(i,j)<1.e+06)
                            fstable(k)=fnx(i,j);
                            [~,ii]=min(abs(fn-fstable(k)));
                            fns(ii)=1;
                            k=k+1;
                            break;
                        end    
                    end    
                end    
            end    

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            big=[0 0 0];
            
            kv=1;
            
            sz=size(fn);
            
            
            for ijk=1:1
                
                out1=sprintf(' Mode \t  fn \t damping \t Q ');
                out2=sprintf('      \t (Hz)\t ratio ');    
                disp(out1);
                disp(out2);   

                out11=sprintf('  n  \t  fn \t   damping \t Q ');
                sq=sprintf('%s\n%s\n',out11,out2);
            
                for i=1:sz(1)
                    
                    if(nn==1 || mnum<=1)
                        out3=sprintf(' %d \t %7.4g \t %7.3f \t %4.1f ',i,fn(i,ijk),dr(i,ijk),1/(2*dr(i,ijk)));
                    else

                        if(fns(i)==1)
                            out3=sprintf(' %d \t %7.4g \t %7.3f \t %4.1f \t stable fn',i,fn(i,ijk),dr(i,ijk),1/(2*dr(i,ijk)));
                        else
                            out3=sprintf(' %d \t %7.4g \t %7.3f \t %4.1f \t unstable fn',i,fn(i,ijk),dr(i,ijk),1/(2*dr(i,ijk)));
                        end    
                    end

                    fprintf(' %d \t %7.4g \t %7.3f \t %4.1f \n',i,fn(i,ijk),dr(i,ijk),1/(2*dr(i,ijk)));
                    sq=sprintf('%s\n%s',sq,out3);
                    big(kv,1)=fn(i,ijk);
                    big(kv,2)=dr(i,ijk);
                    big(kv,3)=1/(2*dr(i,ijk));
                    kv=kv+1;
                end
            
            end

            md=5;

            t_string=sprintf('%s',tts);
            
            app.y_label=sprintf('Magnitude (%s/%s)',app.bu,app.au);
    
            [app.fig_num]=plot_frf_md_two(app.fig_num,f,frf,ofrf,app.fmin,app.fmax,t_string,app.y_label,md);

            app.ResultsTextArea.Value=sq;

          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CPSDH1H2UIFigure = uifigure('Visible', 'off');
            app.CPSDH1H2UIFigure.Name = 'CPSD H1 H2';
            app.Label = uilabel(app.CPSDH1H2UIFigure);
            app.Label.Text = 'cpsd_H1_H2_a   ver 2.0   by Tom Irvine   This script calculates the cross power density and H1 & H2 of two time histories.  ';
            app.TabGroup = uitabgroup(app.CPSDH1H2UIFigure);
            app.DataEntryTab = uitab(app.TabGroup);
            app.DataEntryTab.Title = 'Data Entry';
            app.ReadDataButton = uibutton(app.DataEntryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.SignalProcessingListBoxLabel = uilabel(app.DataEntryTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.DataEntryTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.ApplyTimeLimitsButton = uibutton(app.DataEntryTab, 'push');
            app.ApplyTimeLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTimeLimitsButtonPushed, true);
            app.ApplyTimeLimitsButton.Text = 'Apply Time Limits';
            app.ListboxMethod = uilistbox(app.DataEntryTab);
            app.ListboxMethod.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod.ValueChangedFcn = createCallbackFcn(app, @ListboxMethodValueChanged, true);
            app.ListboxMethod.Tag = 'listbox_method';
            app.ListboxMethod.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.DataEntryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.ListboxFormat = uilistbox(app.DataEntryTab);
            app.ListboxFormat.Items = {'Common array:  time(sec) & amplitude & amplitude', 'Two separate arrays, each:  time(sec) & amplitude'};
            app.ListboxFormat.ValueChangedFcn = createCallbackFcn(app, @ListboxFormatValueChanged, true);
            app.ListboxFormat.Tag = 'listbox_method';
            app.ListboxFormat.Value = 'Two separate arrays, each:  time(sec) & amplitude';
            app.text1_2 = uilabel(app.DataEntryTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Format';
            app.UpdateListButton = uibutton(app.DataEntryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed2, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SelectResponseInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectResponseInputArrayListBoxLabel.Text = 'Select Response Input Array';
            app.SelectResponseInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectResponseInputArrayListBox.Items = {};
            app.SelectResponseInputArrayListBox.Value = {};
            app.InputListBoxLabel = uilabel(app.DataEntryTab);
            app.InputListBoxLabel.Text = 'Input';
            app.InputListBox = uilistbox(app.DataEntryTab);
            app.InputListBox.Items = {'Accel (G)', 'Force (lbf)', 'Force (N)', 'Pressure (psi)', 'Pressure (Pa)'};
            app.InputListBox.Value = 'Accel (G)';
            app.ResponseListBoxLabel = uilabel(app.DataEntryTab);
            app.ResponseListBoxLabel.Text = 'Response';
            app.ResponseListBox = uilistbox(app.DataEntryTab);
            app.ResponseListBox.Items = {'Accel (G)', 'Velocity (in/sec)', 'Velocity (m/sec)', 'Displacement (in)', 'Displacement (m)', 'Displacement (mm)', 'Pressure (psi)', 'Pressure (Pa)'};
            app.ResponseListBox.Value = 'Accel (G)';
            app.CPSDH1H2Tab = uitab(app.TabGroup);
            app.CPSDH1H2Tab.Title = 'CPSD, H1, H2';
            app.CPSDH1H2Tab.ButtonDownFcn = createCallbackFcn(app, @CPSDH1H2TabButtonDown, true);
            app.CalculateButton = uibutton(app.CPSDH1H2Tab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.CPSDH1H2Tab);
            app.UITable.ColumnName = {'No. of Segments'; 'Samples/Segments'; 'Time/Segment (sec)'; 'df (Hz)'; 'dof'};
            app.UITable.RowName = {};
            app.SelectdofListBoxLabel = uilabel(app.CPSDH1H2Tab);
            app.SelectdofListBoxLabel.Text = 'Select dof';
            app.SelectdofListBox = uilistbox(app.CPSDH1H2Tab);
            app.SelectdofListBox.ValueChangedFcn = createCallbackFcn(app, @SelectdofListBoxValueChanged, true);
            app.ProcessingOptionsLabel = uilabel(app.CPSDH1H2Tab);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.MeanRemovalListBoxLabel = uilabel(app.CPSDH1H2Tab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.CPSDH1H2Tab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.ValueChangedFcn = createCallbackFcn(app, @MeanRemovalListBoxValueChanged, true);
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.CPSDH1H2Tab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.CPSDH1H2Tab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Hanning';
            app.PlotFrequenciesHzLabel = uilabel(app.CPSDH1H2Tab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.CPSDH1H2Tab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CPSDH1H2Tab, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.CPSDH1H2Tab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CPSDH1H2Tab, 'numeric');
            app.MaxEditField.Value = 2000;
            app.PeakResultsEditFieldLabel = uilabel(app.CPSDH1H2Tab);
            app.PeakResultsEditFieldLabel.Text = 'Peak Results';
            app.PeakResultsEditField = uieditfield(app.CPSDH1H2Tab, 'text');
            app.HanningisbestchoiceforCPSDH1H2Label = uilabel(app.CPSDH1H2Tab);
            app.HanningisbestchoiceforCPSDH1H2Label.Text = 'Hanning is best choice for CPSD, H1, H2';
            app.CPSDCheckBox = uicheckbox(app.CPSDH1H2Tab);
            app.CPSDCheckBox.Enable = 'off';
            app.CPSDCheckBox.Text = 'CPSD';
            app.CPSDCheckBox.Value = true;
            app.H1H2CheckBox = uicheckbox(app.CPSDH1H2Tab);
            app.H1H2CheckBox.Enable = 'off';
            app.H1H2CheckBox.Text = 'H1, H2';
            app.H1H2CheckBox.Value = true;
            app.OutputLabel = uilabel(app.CPSDH1H2Tab);
            app.OutputLabel.Enable = 'off';
            app.OutputLabel.Text = 'Output';
            app.ExportFunctionsTab = uitab(app.TabGroup);
            app.ExportFunctionsTab.Title = 'Export Functions';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportFunctionsTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportFunctionsTab, 'text');
            app.FunctionListBoxLabel = uilabel(app.ExportFunctionsTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportFunctionsTab);
            app.FunctionListBox.Items = {'PSD Input', 'PSD Response', 'CPSD'};
            app.FunctionListBox.Value = 'PSD Input';
            app.SaveButton = uibutton(app.ExportFunctionsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.H1H2ModalCurvefitTab = uitab(app.TabGroup);
            app.H1H2ModalCurvefitTab.Title = 'H1, H2, Modal Curve-fit';
            app.Label_2 = uilabel(app.H1H2ModalCurvefitTab);
            app.Label_2.Text = 'H1: minimize uncorrelated output noise.   H2: minimize uncorrelated input noise.     Methods are Matlab''s modalfit & modalsd functions.';
            app.Label_3 = uilabel(app.H1H2ModalCurvefitTab);
            app.Label_3.Text = 'The input arrays must have two columns:   frequency & receptance FRFs in complex format.     Receptance is (Displacement/Force)';
            app.NumberofModesListBoxLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.NumberofModesListBoxLabel.Text = 'Number of Modes';
            app.NumberofModesListBox = uilistbox(app.H1H2ModalCurvefitTab);
            app.NumberofModesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModesListBox.Value = '1';
            app.FunctionListBox_2Label = uilabel(app.H1H2ModalCurvefitTab);
            app.FunctionListBox_2Label.Text = 'Function';
            app.FunctionListBox_modal = uilistbox(app.H1H2ModalCurvefitTab);
            app.FunctionListBox_modal.Items = {'H1', 'H2'};
            app.FunctionListBox_modal.Value = 'H1';
            app.MethodListBoxLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.MethodListBoxLabel.Text = 'Method';
            app.MethodListBox = uilistbox(app.H1H2ModalCurvefitTab);
            app.MethodListBox.Items = {'Peak-Picking Method', 'Least-Squares Rational Function Estimation ', 'Least-Squares Complex Exponential'};
            app.MethodListBox.Value = 'Peak-Picking Method';
            app.FrequenciesHzLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.FrequenciesHzLabel.Text = 'Frequencies (Hz)';
            app.MinEditField_2Label = uilabel(app.H1H2ModalCurvefitTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_modal = uieditfield(app.H1H2ModalCurvefitTab, 'text');
            app.MaxEditField_2Label = uilabel(app.H1H2ModalCurvefitTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_modal = uieditfield(app.H1H2ModalCurvefitTab, 'text');
            app.CalculateButton_modal = uibutton(app.H1H2ModalCurvefitTab, 'push');
            app.CalculateButton_modal.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_modalPushed, true);
            app.CalculateButton_modal.Text = 'Calculate';
            app.H1FRFInputArrayNameEditFieldLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.H1FRFInputArrayNameEditFieldLabel.Text = 'H1 FRF Input Array Name';
            app.H1FRFInputArrayNameEditField = uieditfield(app.H1H2ModalCurvefitTab, 'text');
            app.H1FRFInputArrayNameEditField.Value = 'H1_complex';
            app.H2FRFInputArrayNameEditFieldLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.H2FRFInputArrayNameEditFieldLabel.Text = 'H2 FRF Input Array Name';
            app.H2FRFInputArrayNameEditField = uieditfield(app.H1H2ModalCurvefitTab, 'text');
            app.H2FRFInputArrayNameEditField.Value = 'H2_complex';
            app.ResultsTextAreaLabel = uilabel(app.H1H2ModalCurvefitTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.H1H2ModalCurvefitTab);
            app.ReturnButton = uibutton(app.CPSDH1H2UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.CPSDH1H2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cpsd_H1_H2_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CPSDH1H2UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CPSDH1H2UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CPSDH1H2UIFigure)
        end
    end
end