classdef cylinder_fn_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CylinderfnUIFigure              matlab.ui.Figure
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        ViewSEAEquationButton           matlab.ui.control.Button
        EnterDataPanel                  matlab.ui.container.Panel
        BoundaryConditionsListBox       matlab.ui.control.ListBox
        BoundaryConditionsListBoxLabel  matlab.ui.control.Label
        SpeedofSoundftsecEditField      matlab.ui.control.NumericEditField
        SpeedofSoundftsecLabel          matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.NumericEditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        DiameterinEditField             matlab.ui.control.NumericEditField
        DiameterinEditFieldLabel        matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomogeneousTab                  matlab.ui.container.Tab
        ThicknessinEditField            matlab.ui.control.NumericEditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.NumericEditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.NumericEditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialLabel                   matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        HoneycombSandwichTab            matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        FaceSheetMaterialListBox        matlab.ui.control.ListBox
        FaceSheetMaterialListBoxLabel   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PlotModeTab                     matlab.ui.container.Tab
        ModeShapeScaleFactorEditField   matlab.ui.control.NumericEditField
        ModeShapeScaleFactorEditFieldLabel  matlab.ui.control.Label
        PlotRingModeButton              matlab.ui.control.Button
        PlotModeShapeButton             matlab.ui.control.Button
        ModeNumberEditField             matlab.ui.control.NumericEditField
        ModeNumberEditFieldLabel        matlab.ui.control.Label
        cylinder_fn_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iu    % Description
        fc    %
        imax  %
        fn    %
        fring %
        ppp   %
        ccc   %
        sea_mph %
        md    %
        ir    %
        fig_num %
        diam  %
        L     %
        kL    %
        LCn   %  
        bc    %
        mat   %
        mface %
        mmax  %
        nmax  %
        kv    %
        CL    %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end   
            try
                close Figure 8;
            catch
            end             
        end
        
        function change(app)
        
            Units(app);   

            n=app.iu;
   
            if(n==1)
                s1='Diameter (in)';
                sem='Elastic Modulus (psi)';
                smd='Mass Density (lbm/in^3)';
                sth='Thickness (in)';  
                sh='Length (in)';
                sss='Speed of Sound (ft/sec)';
                app.ccc=1125;
            else
                s1='Diameter (m)';
                sem='Elastic Modulus (GPa)';
                smd='Mass Density (kg/m^3)'; 
                sth='Thickness (mm)';    
                sh='Length (m)'; 
                sss='Speed of Sound (m/sec)';
                app.ccc=343;
            end
            
            app.SpeedofSoundftsecEditField.Value=app.ccc;
            app.SpeedofSoundftsecLabel.Text=sss;           
            
            app.DiameterinEditFieldLabel.Text=s1;
            app.ElasticModuluspsiEditFieldLabel.Text=sem;
            app.MassDensitylbmin3EditFieldLabel.Text=smd;
            app.ThicknessinEditFieldLabel.Text=sth;
            app.LengthinEditFieldLabel.Text=sh;

            value = app.MaterialListBox.Value;          
            
            if(contains(value,'Aluminum'))
                m=1;
            end
            if(contains(value,'Steel'))
                m=2;
            end
            if(contains(value,'Copper'))
                m=3;
            end
            if(contains(value,'G10'))
                m=4;
            end
            if(contains(value,'Titanium'))
                m=5;
            end
            if(contains(value,'Graphite/Epoxy'))
                m=6;       
            end 
            
            app.mat=m;
            
            [E,rho,poisson]=six_materials(n,m);

            app.ElasticModuluspsiEditField.Value=E;
            app.MassDensitylbmin3EditField.Value=rho;        
            app.PoissonRatioEditField.Value=poisson;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            value = app.FaceSheetMaterialListBox.Value;          
            
            if(contains(value,'Aluminum'))
                app.mface=1;
            end
            if(contains(value,'Steel'))
                app.mface=2;
            end
            if(contains(value,'Copper'))
                app.mface=3;
            end
            if(contains(value,'G10'))
                app.mface=4;
            end
            if(contains(value,'Titanium'))
                app.mface=5;
            end
            if(contains(value,'Graphite/Epoxy'))
                app.mface=6;       
            end                  
            
            [E,rho,poisson]=six_materials(app.iu,app.mface);
            
            app.UITable.ColumnEditable = [false, true];
            app.UITable.ColumnFormat = {'char','numeric'};
            app.UITable.ColumnWidth={240,60 };
            
            for i=1:7
                myData{i,2}='';
            end
            
            myData{2,2}=sprintf('%8.4g',E);
            myData{3,2}=num2str(rho);   
            myData{4,2}=num2str(poisson);            
            
            if(n==1)
                myData{1,1}='Face Sheet: Individual Thickness (in)';
                myData{2,1}='Face Sheet: Elastic Modulus (psi)';
                myData{3,1}='Face Sheet: Mass Density (lbm/in^3)';
                myData{4,1}='Face Sheet: Poisson Ratio';
                myData{5,1}='Core: Shear Modulus (psi)';
                myData{6,1}='Core: Mass Density (lbm/in^3)';
                myData{7,1}='Core: Thickness (in)';       
            else
                myData{1,1}='Face Sheet: Individual Thickness (mm)';
                myData{2,1}='Face Sheet: Elastic Modulus (GPa)';
                myData{3,1}='Face Sheet: Mass Density (kg/m^3)';
                myData{4,1}='Face Sheet: Poisson Ratio';
                myData{5,1}='Core: Shear Modulus (MPa)';
                myData{6,1}='Core: Mass Density (kg/m^3)';
                myData{7,1}='Core: Thickness (mm)';                
            end            
            
            app.UITable.Data=myData; 
            
            app.nmax=180;
            app.mmax=180;
        end
        
        function post(app)
        
            app.ppp=app.md;
            
            fmin=app.ppp(1,1);
            fmax=app.ppp(end,1);            
            
            sz=size(app.ppp);             
                
            for i=sz(1):-1:1
                if(app.ppp(i,2)==0)
                    app.ppp(i,:)=[];
                end  
            end
                
            ppp1=app.ppp;
            ppp2=app.sea_mph;               
        
            try
                app.fig_num=1;
                
                t_string='Cylinder Modal Density, Wave Method, One-Third Octave Bands';
                y_label='Modes/Hz';
                x_label='Frequency (Hz)';
                    
                
                [app.fig_num]=plot_loglog_function_md(app.fig_num,x_label,y_label,t_string,app.ppp,fmin,fmax,4);
            catch
            end
            try
                t_string='Cylinder Modal Density, One-Third Octave Bands';
                y_label='Modes/Hz';
                x_label='Frequency (Hz)';
                mdp=4;
                
                leg1='Wave Method';
                leg2='SEA Approximation';
               
                
                [app.fig_num,~]=plot_loglog_function_md_two_h2(app.fig_num,x_label,...
                               y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,mdp);
                           
            catch               
            end
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
                    
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                plot(app.fn(1:length(app.ccc),1),app.ccc,'bo','MarkerSize',3);
                grid on;
                title('Wave Speed');
                xlabel('Frequency (Hz)');
                if(app.iu==1)
                    ylabel('Speed (ft/sec)');
                else
                    ylabel('Speed (m/sec)');     
                end
                 
                set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','log');  
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
                
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);    
                end   
                
                app.md=4;
                
                ymax= 10^ceil(0.01+log10(max(app.ccc)));
                ymin= 10^floor(log10(min(app.ccc)));
                
                if(ymin<ymax/10^mdp)
                    ymin=ymax/10^mdp;
                end
                
                if(ymin==ymax)
                    ymin=ymin/10;
                    ymax=ymax*10;
                end    
                
                ylim([ymin,ymax]);
                
            catch
            end
        
            assignin('base','cyl_modal_density',app.ppp);
            assignin('base','cyl_fn',[app.fn(:,1) app.fn(:,3) app.fn(:,4)]);
             
            fprintf('\n Output Arrays for Wave Method \n');
            fprintf('\n       Modal Density:  cyl_modal_density   [fc(Hz) (modes/Hz)] ');
            fprintf('\n Natural Frequencies:  cyl_fn              [fn(Hz)  m  n] \n\n');

            msgbox('Results written to Command Window');
        end
        
        function PlotModes(app)
            disp(' ');
            disp(' plotting... ');
            
            scale=app.ModeShapeScaleFactorEditField.Value;
            
            nq=app.ModeNumberEditField.Value;
            
            if(nq>length(app.fn(:,1)))
                out1=sprintf(' Maximum mode number = %d',length(app.fn));
                warndlg(out1);
                return;
            end
            
            %%%%
            LN=64;
            
            radius=app.diam/2;
            
            dL=app.L/LN;
            dT=pi/45;

            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            
            hold on;
            
            m=app.fn(nq,3);
            n=app.fn(nq,4);
            
            
            nnt=90; 
             
            dtd=360/nnt; 
             
            theta=zeros(nnt,1);
            
            for i=1:nnt
                theta(i)=(i-1)*dtd;
            end   
            
            gamma=dtd*pi/180;
            
            dT=2*radius*pi/720;
            theta=theta*pi/180;
            
            ntheta=n*theta;
            ndT=n*dT;
            
            
            k=app.kL(m)/app.L;
            dr=radius/15; 
            ddL=dL/0.5;
            
            bL=app.kL(m);
            
            MSb=app.fn(nq,5:7);
            
            sq=max(abs(MSb));
            
            if(abs(MSb(3))>1.0e-04)
                sq=sq/sign(MSb(3));
            end
            
            scale1=scale*(radius/25)*MSb(1)/sq;
            scale2=scale*(radius/25)*MSb(2)/sq;
            scale3=scale*(radius/25)*MSb(3)/sq;

            fprintf('\n  dT=%8.4g  radius=%8.4g \n',dT,radius);
            fprintf('\n  scale1=%8.4g  scale2=%8.4g  scale3=%8.4g  \n',scale1,scale2,scale3);
            
            dr=dr*scale3;
            ddL=ddL*scale1;
            
            for j=1:LN
                  
                L1=(j-1)*dL;
                L2=L1+dL;
                    
                for i=1:90
              
                    p=theta(i);
                    np=ntheta(i);
                   
                    arg1=k*L1;
                    arg2=k*L2;
                
                    if(app.bc==1)  % free-free
                        C=(-cosh(bL)+cos(bL))/(sinh(bL)+sin(bL));
                        qmm1=((sinh(arg1)+sin(arg1))+C*(cosh(arg1)+cos(arg1)));
                        qmm2=((sinh(arg2)+sin(arg2))+C*(cosh(arg2)+cos(arg2)));            
                    end
                    if(app.bc==2)  % fixed-fixed
                        C=(sinh(bL)+sin(bL))/(cosh(bL)-cos(bL));
                        if(m<=6)
                            qmm1=((cosh(arg1)-cos(arg1))-C*(sinh(arg1)-sin(arg1)));
                            qmm2=((cosh(arg2)-cos(arg2))-C*(sinh(arg2)-sin(arg2)));
                        else
                            qmm1=1-cos(arg1);
                            qmm2=1-cos(arg2);                
                        end    
                    end
                    if(app.bc==3) % fixed-free
                        C=-(cos(bL)+cosh(bL))/(sin(bL)+sinh(bL));
                        qmm1=((cosh(arg1)-cos(arg1))+C*(sinh(arg1)-sin(arg1)));
                        qmm2=((cosh(arg2)-cos(arg2))+C*(sinh(arg2)-sin(arg2)));                  
                    end
                    if(app.bc==4) % ss-ss
                        qmm1=sin(arg1);
                        qmm2=sin(arg2);
                    end
                    
                    cnp=cos(np);
                    cnpndT=cos(np+ndT);
                    
                    r1=radius + dr*cnp*qmm1;
                    r2=radius + dr*cnpndT*qmm1;
                    r3=radius + dr*cnpndT*qmm2;
                    r4=radius + dr*cnp*qmm2;
                    
                    LL1=L1+ddL*cnp*qmm1;
                    LL2=LL1;   
                    LL3=L2+ddL*cnpndT*qmm2;        
                    LL4=LL3;
            
                    T1= dT*sin(np)*qmm1;
                    T2= dT*sin(np+ndT)*qmm1;
                    T3= dT*sin(np+ndT)*qmm2;
                    T4= dT*sin(np)*qmm2;        
                    
                    alpha1=atan2(T1,radius)*scale2;
                    alpha2=atan2(T2,radius)*scale2;
                    alpha3=atan2(T3,radius)*scale2;
                    alpha4=atan2(T4,radius)*scale2;        
            
                    beta1=p+alpha1;
                    beta2=p+gamma+alpha2;
                    beta3=p+gamma+alpha3;
                    beta4=p+alpha4;  
                    
                    x1=r1*cos(beta1);
                    x2=r2*cos(beta2);   
                    x3=r3*cos(beta3);         
                    x4=r4*cos(beta4);
                               
                    y1=r1*sin(beta1);
                    y2=r2*sin(beta2);   
                    y3=r3*sin(beta3);         
                    y4=r4*sin(beta4);
                    
                    X=[x1 x2 x3 x4 ];
                    Y=[y1 y2 y3 y4 ];
                    
                    Z=[LL1 LL2 LL3 LL4];
                    
            %        if(i==1)
            %            fprintf('%8.4g \n',LL3-LL1);
            %        end
                    
                    patch(X,Y,Z,'g');

                end
            end
            view([-45 45]);
            grid on;
            
            U=max([radius app.L]);
            
            Ur=U;
            
            xlim([ -Ur Ur ]);
            ylim([ -Ur Ur ]);
            
            if(app.LCn(nq)==1)
                LC='AS';
            else
                LC='AF';
            end
            
            if(app.fn(nq,2)==1)
                out1=sprintf(' Mode %d  fn=%8.4g Hz  m=%d n=%d  flexural  %s',nq,app.fn(nq,1),m,n,LC);
            end
            if(app.fn(nq,2)==2)
                out1=sprintf(' Mode %d  fn=%8.4g Hz  m=%d n=%d  in-plane 1  %s',nq,app.fn(nq,1),m,n,LC);
            end
            if(app.fn(nq,2)==3)
                out1=sprintf(' Mode %d  fn=%8.4g Hz  m=%d n=%d  in-plane 3  %s',nq,app.fn(nq,1),m,n,LC);
            end
            
            title(out1);

            if(app.iu==1)
                xlabel('x(in)');
                ylabel('y(in)');
                zlabel('z(in)');
            else
                xlabel('x(m)');
                ylabel('y(m)');
                zlabel('z(m)');    
            end
            
            hold off;            
        end
        
        function BC(app)
            bcs=strtrim(app.BoundaryConditionsListBox.Value);
            
            if(strcmp(bcs,'Free-Free'))
                app.bc=1;
            end
            if(strcmp(bcs,'Fixed-Fixed'))
                app.bc=2;
            end
            if(strcmp(bcs,'Fixed-Free'))
                app.bc=3;
            end
            if(contains(bcs,'Simply'))
                app.bc=4;
            end
        end
        
        function Text(app)
         
            cspeed=app.SpeedofSoundftsecEditField.Value;
        
            disp('  ');
            disp(' n is capp.ircumferential mode parameter, where 2n=the number of ');
            disp('   cross points in the radial displacement shape'); 
            disp(' ');
            disp(' m is axial mode parameter');
            disp(' k is the wave number');
            disp(' c is the speed of sound');
            disp('   ');
            disp(' AF is acoustically fast ');
            disp(' AS is acoustically slow ');
            disp('  ');
            %
            disp(' ')
            if(app.iu==1)
                disp('    fn(Hz)   m   n   k(rad/in)   c(ft/sec)');
            else
                disp('    fn(Hz)   m   n   k(rad/m)   c(m/sec)');    
            end
            
            %
            mmm=app.kv-1;
            if(mmm>6000)
                mmm=6000;
            end
            
            fcand=zeros(3,1);
            icand=zeros(3,1);
            ic=1;
            
            app.ir=0;
            ik=1;
            
            tpi=2*pi;
            
            app.ccc=zeros(mmm,1);
            app.LCn=zeros(mmm,1);
            
            for i=1:mmm
                
                c=tpi*app.fn(i,1)/app.fn(i,8);
                
                if(app.iu==1)
                    c=c/12;
                end
                
                app.ccc(i)=c;
                
                LC='AS';
                app.LCn(i)=1;
                if(c>cspeed)
                    LC='AF';
                    app.LCn(i)=2;
                end    
                
                if(app.fn(i,1)<=20000)
                    if(app.fn(i,2)==1 && i<=2000)
                        fprintf(' %d  %8.2f  %d  %d  %8.3g  %8.5g  flexural %s\n',i,app.fn(i,1),app.fn(i,3),app.fn(i,4),app.fn(i,8),c,LC);        
                    end
                    if(app.fn(i,2)==2 && i<=2000)
                        fprintf(' %d  %8.2f  %d  %d  %8.3g  %8.5g  in-plane 1  %s\n',i,app.fn(i,1),app.fn(i,3),app.fn(i,4),app.fn(i,8),c,LC);        
                    end    
                    if(app.fn(i,2)==3 && i<=2000)
                        fprintf(' %d  %8.2f  %d  %d  %8.3g  %8.5g  in-plane 2  %s\n',i,app.fn(i,1),app.fn(i,3),app.fn(i,4),app.fn(i,8),c,LC);        
                    end    
                end
                
                if(app.fn(i,2)==1)
                    flex(ik,:)=[app.fn(i,1),app.fn(i,3),app.fn(i,4)];
                    ik=ik+1;
                end
                
                if(app.fn(i,3)==1 && app.fn(i,4)==0)
                    fcand(ic)=app.fn(i,1);
                    icand(ic)=i;
                    ic=ic+1;
                end
                
            end
            
            [~,idx]=min(abs(fcand-app.fring));
            
            app.ir=icand(idx);
            
            
            if(app.ir>=1)
                
                c=tpi*app.fn(app.ir,1)/app.fn(app.ir,8);
                
                if(app.iu==1)
                    c=c/12;
                end
                
                LC='AS';
                if(c>cspeed)
                    LC='AF';
                end
                
                if(app.iu==1)
                    fprintf('\n Ring Mode Frequency: \n   %d  %8.2f Hz  m=%d  n=%d  k=%8.3g rad/in  c=%8.5g ft/sec  in-plane 1  %s\n',app.ir,app.fn(app.ir,1),app.fn(app.ir,3),app.fn(app.ir,4),app.fn(app.ir,8),c,LC);   
                else
                    fprintf('\n Ring Mode Frequency: \n   %d  %8.2f Hz  m=%d  n=%d  k=%8.3g rad/m  c=%8.5g m/sec   in-plane 1  %s\n',app.ir,app.fn(app.ir,1),app.fn(app.ir,3),app.fn(app.ir,4),app.fn(app.ir,8),c,LC);           
                end
                fprintf('    Modal Coefficients:  Long=%6.3g    Tang=%6.3g    Rad=%6.3g \n',app.fn(app.ir,5),app.fn(app.ir,6),app.fn(app.ir,7));
            
            end
               
            if(app.fring>0)
                fprintf('\n Infinite Length Ring Frequency: %8.4g Hz \n',app.fring);
            end
            
            if(app.bc==1 || app.bc==2 || app.bc==4)
                flong=pi*app.CL/app.L;
            end
                
            if(app.bc==3) % Fixed-Free
                flong=pi*app.CL/(2*app.L); 
            end
                
            flong=flong/tpi;
            
            fprintf('\n First Longitudinal Mode f = %8.5g Hz \n',flong);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            sz=size(app.fn);
            app.kv=sz(1);

            NM=min([length(flex(:,1)) app.kv ]);
            NM=NM-1;
            
            nun=zeros([1 app.imax]);
            for j=1:app.imax
                fl=app.fc(j)/sqrt(2);
                fu=app.fc(j)*sqrt(2);
                for i=1:NM
                    if(app.fn(i,1)>=fl && app.fn(i,1) < fu)
                       nun(j)=nun(j)+1;    
                    end
                    if(flex(i,1)>fu)
                        break;
                    end
                end    
            end
            %
            for j=1:app.imax
                fl=app.fc(j)/sqrt(2);
                fu=app.fc(j)*sqrt(2);  
                nun(j)=nun(j)/(fu-fl);
            end
            %
            app.md=[app.fc' nun'];
            app.md=sortrows(app.md,1);
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            
        end
        
        function Units(app)
            value = strtrim(app.UnitsListBox.Value);          

            if(strcmp(value,'English'))
                app.iu=1;
            else
                app.iu=2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             BC(app);
             change(app);
            
                app.fc(1)=2.5;
            	app.fc(2)=3.15;
            	app.fc(3)=4.;
            	app.fc(4)=5.;
            	app.fc(5)=6.3;
            	app.fc(6)=8.;
            	app.fc(7)=10.;
            	app.fc(8)=12.5;
            	app.fc(9)=16.;
            	app.fc(10)=20.;
            	app.fc(11)=25.;
            	app.fc(12)=31.5;
            	app.fc(13)=40.;
            	app.fc(14)=50.;
            	app.fc(15)=63.;
            	app.fc(16)=80.;
            	app.fc(17)=100.;
            	app.fc(18)=125.;
            	app.fc(19)=160.;
            	app.fc(20)=200.;
            	app.fc(21)=250.;
            	app.fc(22)=315.;
            	app.fc(23)=400.;
            	app.fc(24)=500.;
            	app.fc(25)=630.;
            	app.fc(26)=800.;
            	app.fc(27)=1000.;
            	app.fc(28)=1250.;
            	app.fc(29)=1600.;
            	app.fc(30)=2000.;
            	app.fc(31)=2500.;
            	app.fc(32)=3150.;
            	app.fc(33)=4000.;
            	app.fc(34)=5000.;
            	app.fc(35)=6300.;
            	app.fc(36)=8000.;
            	app.fc(37)=10000.;
                app.imax=length(app.fc);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            ClosePlots(app);
            Units(app);
            BC(app);
            
            fprintf('\n * * * * * * * * * * * \n');
            
            E=app.ElasticModuluspsiEditField.Value;
            rho=app.MassDensitylbmin3EditField.Value;
            v=app.PoissonRatioEditField.Value;
            h=app.ThicknessinEditField.Value;
            app.L=app.LengthinEditField.Value;
            app.diam=app.DiameterinEditField.Value;
            
            if(app.iu==1)     % English
                rho=rho/386.;
            else              % metric
                [E]=GPa_to_Pa(E);
                h=h/1000;
            end
            
            app.CL=sqrt(E/rho);
            
            %  c=sqrt(E/rho);
            
            BC(app);            
            
            %
            
            [app.fring]=infinite_cylinder_ring(E,rho,v,h,app.diam);
            
            [app.fn,app.kv,~,app.kL]=cylinder_engine_alt(E,rho,v,h,app.diam,app.L,app.mmax,app.nmax,app.bc);
 
            Text(app);
            
            NL=length(app.ppp);
            
            app.sea_mph=zeros(NL,2);
             
            for i=1:NL 
                xy = cylinder_mdens(app.ppp(i,1),app.fring,app.L,h);
                app.sea_mph(i,:)=[app.ppp(i,1) xy];
            end   
                        
            post(app);
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);
            Units(app);
            BC(app);
            
            fprintf('\n * * * * * * * * * * * \n'); 
            
            app.L=app.LengthinEditField.Value;
            app.diam=app.DiameterinEditField.Value;

            B=app.UITable.Data;
            B=char(B);

            tf=str2double(strtrim(B(8,:)));
            E=str2double(strtrim(B(9,:)));
            rhof=str2double(strtrim(B(10,:)));
            mu=str2double(strtrim(B(11,:)));
            G=str2double(strtrim(B(12,:)));
            rhoc=str2double(strtrim(B(13,:)));            
            hc=str2double(strtrim(B(14,:)));
            
            if(app.iu==1)     % English
                rhof=rhof/386;
                rhoc=rhoc/386;
            else              % metric
                [E]=GPa_to_Pa(E);
                [G]=MPa_to_Pa(G);               
                hc=hc/1000;
                tf=tf/1000;
            end
            
            app.CL=sqrt(E/rhof);
            
            [~,~,~,mpa]=honeycomb_sandwich_properties(E,G,mu,tf,hc,rhof,rhoc);

            [D,K,~,~,app.fring]=honeycomb_sandwich_properties_wave(E,G,mu,tf,hc,app.diam,rhof,rhoc);
            [f11,f12]=shear_frequencies(E,G,mu,tf,hc,rhof,rhoc);            
                  
            [app.fn,app.kv,~,app.kL]=...
                         cylinder_engine_sandwich(D,K,mpa,mu,app.diam,app.L,app.mmax,app.nmax,app.bc);            

            Text(app);
            
            h=hc+2*tf;
            
            NL=length(app.ppp);
            
            app.sea_mph=zeros(NL,2);
             
            for i=1:NL 
                xy = cylinder_mdens(app.ppp(i,1),app.fring,app.L,h);
                app.sea_mph(i,:)=[app.ppp(i,1) xy];
            end   


            post(app);             
            
            display_shear_frequencies(f11,f12);
            disp(' ');                         
                         
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            change(app);
        end

        % Button pushed function: ViewSEAEquationButton
        function ViewSEAEquationButtonPushed(app, event)
            A = imread('Cylinder_md.jpg');
            figure(998) 
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: PlotRingModeButton
        function PlotRingModeButtonPushed(app, event)
            app.ModeNumberEditField.Value=app.ir;
            PlotModes(app);
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)
            PlotModes(app);
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            
            % app.BoundaryConditionsListBox.Value=app.BoundaryConditionsListBox.Items(2);

            try
                CylinderWave.Data=app.UITable.Data;
            catch
                fprintf('\n\n Failed: CylinderWave.data=app.UITable.Data \n\n');
            end 
            try
                CylinderWave.mface=app.mface;
            catch    
            end            
            try
                CylinderWave.sandwich=app.sandwich;
            catch    
            end
            try
                CylinderWave.mat=app.mat;
            catch    
            end
            try
                em=app.ElasticModuluspsiEditField.Value;
                CylinderWave.em=em;
            catch    
            end 
            try
                mass_dens=app.MassDensitylbmin3EditField.Value;
                CylinderWave.mass_dens=mass_dens;
            catch    
            end  
            try
                pois=app.PoissonRatioEditField.Value;
                CylinderWave.pois=pois;
            catch    
            end  
            try
                thick=app.ThicknessinEditField.Value;
                CylinderWave.thick=thick;
            catch    
            end                
            
            try
                CylinderWave.units=app.iu;
            catch    
            end
            
            try
                CylinderWave.bc=app.bc;
            catch    
            end            
            try
                diameter=app.DiameterinEditField.Value;
                CylinderWave.diameter=diameter;
            catch    
            end
            try
                LLL=app.LengthinEditField.Value;
                CylinderWave.LLL=LLL;
            catch    
            end
            try
                speed_sound=app.SpeedofSoundftsecEditField.Value;
                CylinderWave.speed_sound=speed_sound;
            catch    
            end            
            
            % % %
            
            structnames = fieldnames(CylinderWave, '-full'); % fields in the struct
            
            % % %
            
               [writefname, writepname] = uiputfile('*.mat','Save data as');
            
               %%%    writepfname = fullfile(writepname, writefname);
                
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
               
                elk=sprintf('%s%s',writepname,writefname);
            
                try
             
                    save(elk, 'CylinderWave'); 
             
                catch
                    warndlg('Save error');
                    return;
                end
        end

        % Value changed function: BoundaryConditionsListBox
        function BoundaryConditionsListBoxValueChanged(app, event)
            BC(app);
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            

        [filename, pathname] = uigetfile('*.mat');
 
        NAME = [pathname,filename];
        
        struct=load(NAME);
        structnames = fieldnames(struct, '-full'); % fields in the struct
        
        k=length(structnames);
        
        for i=1:k
            namevar=strcat('struct.',structnames{i});
            value=eval(namevar);
            assignin('base',structnames{i},value);
        end
        
        % struct
        
        try
        
           CylinderWave=evalin('base','CylinderWave');
        
        catch
           warndlg(' evalin failed ');
           return;
        end


        % app.BoundaryConditionsListBox.Value=app.BoundaryConditionsListBox.Items(2);

            try
                app.UITable.Data=CylinderWave.Data;
            catch    
            end 
            try
                app.mface=CylinderWave.mface; 
                app.FaceSheetMaterialListBox.Value=app.FaceSheetMaterialListBox.Items(app.mface);
            catch    
            end            
            try
                app.sandwich=CylinderWave.sandwich;
                app.FaceSheetMaterialListBox.Items(app.sandwich);
            catch    
            end
            try
                app.mat=CylinderWave.mat;
                app.MaterialListBox.Value=app.MaterialListBox.Items(app.mat);
            catch    
            end
            
            try
                em=CylinderWave.em;               
                app.ElasticModuluspsiEditField.Value=em;
            catch    
            end 
            try
                mass_dens=CylinderWave.mass_dens;               
                app.MassDensitylbmin3EditField.Value=mass_dens;
            catch    
            end  
            try
                pois=CylinderWave.pois;               
                app.PoissonRatioEditField.Value=pois;
            catch    
            end  
            try
                thick=CylinderWave.thick;                
                app.ThicknessinEditField.Value=thick;
            catch    
            end                
            
            try
                app.iu=CylinderWave.units;  
                app.UnitsListBox.Value=app.BoundaryConditionsListBox.Items(app.iu);
            catch    
            end
            
            try
                app.bc=CylinderWave.bc;  
                app.BoundaryConditionsListBox.Value=app.BoundaryConditionsListBox.Items(app.bc);
            catch    
            end            
            try
                diameter=CylinderWave.diameter;                
                app.DiameterinEditField.Value=diameter;
            catch    
            end
            try
                LLL=CylinderWave.LLL;              
                app.LengthinEditField.Value=LLL;
            catch    
            end
            try
                speed_sound=CylinderWave.speed_sound;                
                app.SpeedofSoundftsecEditField.Value=speed_sound;
            catch    
            end            
            
        end

        % Value changed function: FaceSheetMaterialListBox
        function FaceSheetMaterialListBoxValueChanged(app, event)
            change(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CylinderfnUIFigure = uifigure('Visible', 'off');
            app.CylinderfnUIFigure.Name = 'Cylinder fn';
            app.cylinder_fn_aver10byTomIrvineLabel = uilabel(app.CylinderfnUIFigure);
            app.cylinder_fn_aver10byTomIrvineLabel.Text = 'cylinder_fn_a    ver 1.0   by Tom Irvine';
            app.TabGroup = uitabgroup(app.CylinderfnUIFigure);
            app.HomogeneousTab = uitab(app.TabGroup);
            app.HomogeneousTab.Title = 'Homogeneous';
            app.CalculateButton_2 = uibutton(app.HomogeneousTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.MaterialLabel = uilabel(app.HomogeneousTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.HomogeneousTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other', ''};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.HomogeneousTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.HomogeneousTab, 'numeric');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.HomogeneousTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.HomogeneousTab, 'numeric');
            app.PoissonRatioEditFieldLabel = uilabel(app.HomogeneousTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.HomogeneousTab, 'numeric');
            app.ThicknessinEditFieldLabel = uilabel(app.HomogeneousTab);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.HomogeneousTab, 'numeric');
            app.HoneycombSandwichTab = uitab(app.TabGroup);
            app.HoneycombSandwichTab.Title = 'Honeycomb Sandwich';
            app.CalculateButton = uibutton(app.HoneycombSandwichTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FaceSheetMaterialListBoxLabel = uilabel(app.HoneycombSandwichTab);
            app.FaceSheetMaterialListBoxLabel.Text = 'Face Sheet Material';
            app.FaceSheetMaterialListBox = uilistbox(app.HoneycombSandwichTab);
            app.FaceSheetMaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other', ''};
            app.FaceSheetMaterialListBox.ValueChangedFcn = createCallbackFcn(app, @FaceSheetMaterialListBoxValueChanged, true);
            app.FaceSheetMaterialListBox.Value = 'Aluminum';
            app.UITable = uitable(app.HoneycombSandwichTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.PlotModeTab = uitab(app.TabGroup);
            app.PlotModeTab.Title = 'Plot Mode';
            app.ModeNumberEditFieldLabel = uilabel(app.PlotModeTab);
            app.ModeNumberEditFieldLabel.Text = 'Mode Number';
            app.ModeNumberEditField = uieditfield(app.PlotModeTab, 'numeric');
            app.ModeNumberEditField.Value = 1;
            app.PlotModeShapeButton = uibutton(app.PlotModeTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.PlotRingModeButton = uibutton(app.PlotModeTab, 'push');
            app.PlotRingModeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRingModeButtonPushed, true);
            app.PlotRingModeButton.Text = 'Plot Ring Mode';
            app.ModeShapeScaleFactorEditFieldLabel = uilabel(app.PlotModeTab);
            app.ModeShapeScaleFactorEditFieldLabel.Text = 'Mode Shape Scale Factor';
            app.ModeShapeScaleFactorEditField = uieditfield(app.PlotModeTab, 'numeric');
            app.ModeShapeScaleFactorEditField.Value = 1;
            app.Label = uilabel(app.CylinderfnUIFigure);
            app.Label.Text = 'This script calculates the natural frequencies of a cylindrical shell via the wave propagation approach and via an SEA approximation technique.';
            app.ReturnButton = uibutton(app.CylinderfnUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.EnterDataPanel = uipanel(app.CylinderfnUIFigure);
            app.EnterDataPanel.Title = 'Enter Data';
            app.DiameterinEditFieldLabel = uilabel(app.EnterDataPanel);
            app.DiameterinEditFieldLabel.Text = 'Diameter (in)';
            app.DiameterinEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.LengthinEditFieldLabel = uilabel(app.EnterDataPanel);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.UnitsListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.EnterDataPanel);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.SpeedofSoundftsecLabel = uilabel(app.EnterDataPanel);
            app.SpeedofSoundftsecLabel.Text = 'Speed of Sound (ft/sec)';
            app.SpeedofSoundftsecEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.SpeedofSoundftsecEditField.Value = 1125;
            app.BoundaryConditionsListBoxLabel = uilabel(app.EnterDataPanel);
            app.BoundaryConditionsListBoxLabel.Text = 'Boundary Conditions';
            app.BoundaryConditionsListBox = uilistbox(app.EnterDataPanel);
            app.BoundaryConditionsListBox.Items = {'Free-Free                 ', 'Fixed-Fixed               ', 'Fixed-Free                ', 'Simply Supported both Ends', ''};
            app.BoundaryConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsListBoxValueChanged, true);
            app.BoundaryConditionsListBox.Value = 'Free-Free                 ';
            app.ViewSEAEquationButton = uibutton(app.CylinderfnUIFigure, 'push');
            app.ViewSEAEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSEAEquationButtonPushed, true);
            app.ViewSEAEquationButton.Text = 'View SEA Equation';
            app.SaveModelButton = uibutton(app.CylinderfnUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.CylinderfnUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            % Show the figure after all components are created
            app.CylinderfnUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cylinder_fn_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CylinderfnUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CylinderfnUIFigure)
        end
    end
end