classdef cylinder_ring_frequency_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultsTextArea             matlab.ui.control.TextArea
        ResultsTextAreaLabel        matlab.ui.control.Label
        ElasticModuluspsiEditField  matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField  matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        DiameterinEditField         matlab.ui.control.EditField
        DiameterinEditFieldLabel    matlab.ui.control.Label
        ViewEquationsButton         matlab.ui.control.Button
        UnitsListBox                matlab.ui.control.ListBox
        UnitsListBoxLabel           matlab.ui.control.Label
        MaterialListBox             matlab.ui.control.ListBox
        MaterialListBoxLabel        matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        cylinder_ring_frequency_aver15byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        iu % Description
    end
    
    methods (Access = private)

        function Clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end    

        function MatChange(app)
        
            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [imat]=find_index(Items,im);          

            [elastic_modulus,mass_density,~]=six_materials(app.iu,imat);
            
            %%%%
            
             
            if(imat<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
            else
                    ss1=' ';
                    ss2=' ';
            end

            app.ElasticModuluspsiEditField.Value=ss1;
            app.MassDensitylbmin3EditField.Value=ss2;

        end    
        
        function Units(app)

            Clear(app);

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            if(app.iu==1)
                app.DiameterinEditFieldLabel.Text='Diameter (in)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';           
            else
                app.DiameterinEditFieldLabel.Text='Diameter (m)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';               
            end

            MatChange(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Units(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ViewEquationsButton
        function ViewEquationsButtonPushed(app, event)
            A = imread('ring_freq.jpg');
            figure(999);
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
           
            em=str2num(app.ElasticModuluspsiEditField.Value);
            md=str2num(app.MassDensitylbmin3EditField.Value);

            d=str2num(app.DiameterinEditField.Value);

            if(isempty(d))
                warndlg('Enter diameter first');
                return;
            end    
            
            if(app.iu==1)
                md=md/386;    
                su='in/sec';
            else
                [em]=GPa_to_Pa(em);
                su='m/sec';
            end
            
            [fr,CL]=ring_frequency_I(em,md,d);
            
            s1=sprintf('Speed of Sound = %8.4g %s',CL,su);
                        
            s2=sprintf('\n\nRing Frequency = %8.4g Hz',fr);

            app.ResultsTextArea.Value=sprintf('%s %s',s1,s2);
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Units(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            Clear(app);
            
            
            MatChange(app);
            
        end

        % Value changing function: DiameterinEditField
        function DiameterinEditFieldValueChanging(app, event)
             Clear(app);
            
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
             Clear(app);
            
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            
            Clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.cylinder_ring_frequency_aver15byTomIrvineLabel = uilabel(app.UIFigure);
            app.cylinder_ring_frequency_aver15byTomIrvineLabel.Text = 'cylinder_ring_frequency_a   ver 1.5  by Tom Irvine';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialListBoxLabel = uilabel(app.UIFigure);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.UIFigure);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UnitsListBoxLabel = uilabel(app.UIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.UIFigure);
            app.UnitsListBox.Items = {'Engish', 'Metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'Engish';
            app.ViewEquationsButton = uibutton(app.UIFigure, 'push');
            app.ViewEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationsButtonPushed, true);
            app.ViewEquationsButton.Text = 'View Equations';
            app.DiameterinEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterinEditFieldLabel.Text = 'Diameter (in)';
            app.DiameterinEditField = uieditfield(app.UIFigure, 'text');
            app.DiameterinEditField.ValueChangingFcn = createCallbackFcn(app, @DiameterinEditFieldValueChanging, true);
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.UIFigure);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.UIFigure, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.UIFigure);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.UIFigure, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cylinder_ring_frequency_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end