classdef damping_coefficient_matrix_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dampingcoefficientUIFigure     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ModalCalculationTab            matlab.ui.container.Tab
        ReadOnlyNaturalFrequenciesHzTextArea  matlab.ui.control.TextArea
        ReadOnlyNaturalFrequenciesHzTextAreaLabel  matlab.ui.control.Label
        PerformModalAnalysisButton     matlab.ui.control.Button
        UpdateListButton               matlab.ui.control.Button
        StiffnessArrayListBoxLabel     matlab.ui.control.Label
        StiffnessArrayListBox          matlab.ui.control.ListBox
        MassArrayListBox               matlab.ui.control.ListBox
        MassArrayListBoxLabel          matlab.ui.control.Label
        DampingTab                     matlab.ui.container.Tab
        ModalDistributionListBox       matlab.ui.control.ListBox
        ModalDistributionListBoxLabel  matlab.ui.control.Label
        DampingTypeListBox             matlab.ui.control.ListBox
        DampingTypeListBoxLabel        matlab.ui.control.Label
        SaveDampingButton              matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        CalculateTab                   matlab.ui.container.Tab
        ViscousDampingMatrixReadOnlyTextArea  matlab.ui.control.TextArea
        ViscousDampingMatrixReadOnlyTextAreaLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        OutputListBox                  matlab.ui.control.ListBox
        OutputListBoxLabel             matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        damping_coefficient_matrix_aver10byTomIrvineLabel_2  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        damping_coefficient_matrix_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        fn
        omega
        ModeShapes
        MST
        mass
        stiffness
        dm
        C
    end
    
    methods (Access = public)

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else

                for i=1:length(app.fn)
                    ssr{i}=sprintf('Mode %d',i);
                    ssq{i}='10';
                    ssd{i}='0.05';
                end    

                app.UITable.RowName=ssr';
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData=ssq';
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData=ssd';                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;

        end
        
        function update(app)

            [cell_array]=update_2D_array();

            try
                app.MassArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.MassInputArrayListBox.Items);
                app.MassInputArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.MassArrayListBox.Value=app.MassArrayListBox.Items{1};

            %%

            try
                app.StiffnessArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.StiffnessInputArrayListBox.Items);
                app.StiffnessArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.StiffnessArrayListBox.Value=app.StiffnessArrayListBox.Items{1};

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            ChangeDamping(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Button pushed function: PerformModalAnalysisButton
        function PerformModalAnalysisButtonPushed(app, event)
            
            try
                FS=app.MassArrayListBox.Value;
                app.mass=evalin('base',FS);
            catch
                warndlg('Mass array read failed');
                return;
            end

            try
                FS=app.StiffnessArrayListBox.Value;
                app.stiffness=evalin('base',FS);
            catch
                warndlg('Stiffness array read failed');
                return;
            end


            [app.fn,app.omega,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiffness,app.mass,2);

            for i=1:length(app.fn)
                ss{i}=sprintf('%8.4g',app.fn(i));
            end    

            app.ReadOnlyNaturalFrequenciesHzTextArea.Value=ss;
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
         
            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            
            if(isempty(app.fn))
                warndlg('Calculate natural frequency first');
                return;
            end    
            
            n=length(app.fn);

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            DQ=app.UITable.Data;


            if(contains(nD,'Uniform'))

                if(contains(nmn,'Amplification')) 
                    v=1/(2*DQ{1});
                else  
                    v=DQ{1};
                end   
                app.dm=ones(n,1)*v;
                
            else
                ddd=str2num(char(DQ));

                if(contains(nmn,'Amplification')) 

                    for i=1:length(DQ)
                        v=1/(2*ddd(i));
                        app.dm(i)=v;
                    end
                else  
                              
                    for i=1:length(DQ)
                        v=ddd(i);
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            if(isempty(app.fn))
                warndlg('Calculate natural frequency first');
                return;
            end    
            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end              
            
            dof=length(app.fn);
            dampv=app.dm;

            %
            app.C=zeros(dof,dof);
            %
            for i=1:dof
                app.C(i,i)=2*dampv(i)*app.omega(i);
            end
            %
            cc=app.mass*app.ModeShapes*app.C*app.MST*app.mass;

            app.C=cc;

            for i=1:dof
                
                for j=1:dof
                    if(j==1)
                        ss{i}=sprintf('%7.4g',cc(i,j));
                    else    
                        ss{i}=sprintf('%s \t %7.4g',ss{i},cc(i,j));
                    end
                end
            end

            app.ViscousDampingMatrixReadOnlyTextArea.Value=ss;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = strtrim(app.OutputListBox.Items);
            im = strtrim(app.OutputListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.C;
            end
            if(n==2)
                data=app.fn;
            end
            if(n==3)
                data=app.ModeShapes;  
            end
            
            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name,data);
            
            h = msgbox('Save Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dampingcoefficientUIFigure = uifigure('Visible', 'off');
            app.dampingcoefficientUIFigure.Name = 'damping coefficient';
            app.damping_coefficient_matrix_aver10byTomIrvineLabel = uilabel(app.dampingcoefficientUIFigure);
            app.damping_coefficient_matrix_aver10byTomIrvineLabel.Text = 'damping_coefficient_matrix_a   ver 1.0    by Tom Irvine';
            app.ReturnButton = uibutton(app.dampingcoefficientUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.damping_coefficient_matrix_aver10byTomIrvineLabel_2 = uilabel(app.dampingcoefficientUIFigure);
            app.damping_coefficient_matrix_aver10byTomIrvineLabel_2.Text = 'This script calculates the damping coefficient matrix from modal damping';
            app.TabGroup = uitabgroup(app.dampingcoefficientUIFigure);
            app.ModalCalculationTab = uitab(app.TabGroup);
            app.ModalCalculationTab.Title = 'Modal Calculation';
            app.MassArrayListBoxLabel = uilabel(app.ModalCalculationTab);
            app.MassArrayListBoxLabel.Text = 'Mass Array';
            app.MassArrayListBox = uilistbox(app.ModalCalculationTab);
            app.MassArrayListBox.Items = {''};
            app.MassArrayListBox.Value = '';
            app.StiffnessArrayListBox = uilistbox(app.ModalCalculationTab);
            app.StiffnessArrayListBox.Items = {''};
            app.StiffnessArrayListBox.Value = '';
            app.StiffnessArrayListBoxLabel = uilabel(app.ModalCalculationTab);
            app.StiffnessArrayListBoxLabel.Text = 'Stiffness Array';
            app.UpdateListButton = uibutton(app.ModalCalculationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.PerformModalAnalysisButton = uibutton(app.ModalCalculationTab, 'push');
            app.PerformModalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformModalAnalysisButtonPushed, true);
            app.PerformModalAnalysisButton.Text = 'Perform Modal Analysis';
            app.ReadOnlyNaturalFrequenciesHzTextAreaLabel = uilabel(app.ModalCalculationTab);
            app.ReadOnlyNaturalFrequenciesHzTextAreaLabel.Text = {'Read Only'; 'Natural Frequencies (Hz)'};
            app.ReadOnlyNaturalFrequenciesHzTextArea = uitextarea(app.ModalCalculationTab);
            app.ReadOnlyNaturalFrequenciesHzTextArea.Editable = 'off';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ViscousDampingMatrixReadOnlyTextAreaLabel = uilabel(app.CalculateTab);
            app.ViscousDampingMatrixReadOnlyTextAreaLabel.Text = 'Viscous Damping Matrix, Read Only';
            app.ViscousDampingMatrixReadOnlyTextArea = uitextarea(app.CalculateTab);
            app.ViscousDampingMatrixReadOnlyTextArea.Editable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputListBoxLabel = uilabel(app.ExportTab);
            app.OutputListBoxLabel.Text = 'Output';
            app.OutputListBox = uilistbox(app.ExportTab);
            app.OutputListBox.Items = {'Damping Coefficient Matrix', 'Natural Frequencies', 'Normal Modes'};
            app.OutputListBox.Value = 'Damping Coefficient Matrix';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            % Show the figure after all components are created
            app.dampingcoefficientUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = damping_coefficient_matrix_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.dampingcoefficientUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.dampingcoefficientUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dampingcoefficientUIFigure)
        end
    end
end