classdef damping_functions_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DampingFunctionsUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HalfPowerBandwidthExtensionTab  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        InputDataTab                    matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        PlotInputButton                 matlab.ui.control.Button
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        PlotFrequenciesHzLabel          matlab.ui.control.Label
        AmplitudeTypeListBox            matlab.ui.control.ListBox
        AmplitudeTypeListBoxLabel       matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        UpdateListButton                matlab.ui.control.Button
        CalculationTab                  matlab.ui.container.Tab
        PeakFrequencyTolerancePercentEditField  matlab.ui.control.EditField
        PeakFrequencyTolerancePercentEditFieldLabel  matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        MaxEditField_cf                 matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_cf                 matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        CurveFitFrequenciesHzLabel      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ResultsReadOnlyTextArea         matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_3  matlab.ui.control.Label
        LogDecrementMethodTab           matlab.ui.container.Tab
        ResultsReadOnlyTextArea_log     matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel    matlab.ui.control.Label
        LogdecrementmethodforestimatingdampingLabel  matlab.ui.control.Label
        PeaknEditField                  matlab.ui.control.EditField
        PeaknEditFieldLabel             matlab.ui.control.Label
        Peak0EditField                  matlab.ui.control.EditField
        Peak0EditFieldLabel             matlab.ui.control.Label
        NumberofPeriodsnEditField       matlab.ui.control.EditField
        NumberofPeriodsnEditFieldLabel  matlab.ui.control.Label
        CalculateButton_log             matlab.ui.control.Button
        DampingValueConversionTab       matlab.ui.container.Tab
        SomeconversionpairsdependonnaturalfrequencyOthersdonotLabel  matlab.ui.control.Label
        InputDampingUnitListBox         matlab.ui.control.ListBox
        InputDampingUnitListBoxLabel    matlab.ui.control.Label
        InputDampingValueEditField      matlab.ui.control.EditField
        InputDampingValueEditFieldLabel  matlab.ui.control.Label
        ResultsReadOnlyTextArea_convert  matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_2  matlab.ui.control.Label
        UnitListBox                     matlab.ui.control.ListBox
        NaturalFrequencyEditField       matlab.ui.control.EditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        CalculateButton_convert         matlab.ui.control.Button
        damping_functions_aver11byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        method % Description
        THM
        fn
        Q
        damp
    end


    methods (Access = private)
        
        function update(app)
            [cell_array]=update_two_columns_pos();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function PreProcess(app)
            app.ResultsReadOnlyTextArea.Value='';

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

        end    


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResultsReadOnlyTextArea_log.Value='';
            app.ResultsReadOnlyTextArea_convert.Value='';
            update(app);
            ClosePlots(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changing function: NumberofPeriodsnEditField
        function NumberofPeriodsnEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_log.Value='';
        end

        % Value changing function: Peak0EditField
        function Peak0EditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_log.Value='';
        end

        % Value changing function: PeaknEditField
        function PeaknEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_log.Value='';
        end

        % Button pushed function: CalculateButton_log
        function CalculateButton_logPushed(app, event)
            n=str2num(app.NumberofPeriodsnEditField.Value);
            peak_0=str2num(app.Peak0EditField.Value);
            peak_n=str2num(app.PeaknEditField.Value);


            dec=(1/n)*log(peak_0/peak_n);
            %
            
            den=1 + (2*pi/dec)^2;
            
            damping=1/sqrt(den);
            
            app.Q=1/(2*damping);
            
            out1=sprintf(' log decrement = %7.3g \n',dec);
            out2=sprintf(' damping ratio = %7.3g \n',damping);
            out3=sprintf('             Q = %7.3g \n',app.Q);
            
            sss=sprintf(' %s \n %s \n %s ',out1,out2,out3);

            app.ResultsReadOnlyTextArea_log.Value=sss;

        end

        % Value changed function: InputDampingUnitListBox
        function InputDampingUnitListBoxValueChanged(app, event)

            app.ResultsReadOnlyTextArea_convert.Value='';
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            
            app.ResultsReadOnlyTextArea_convert.Value='';
        end

        % Value changing function: InputDampingValueEditField
        function InputDampingValueEditFieldValueChanging(app, event)
 
            app.ResultsReadOnlyTextArea_convert.Value='';
        end

        % Value changing function: NaturalFrequencyEditField
        function NaturalFrequencyEditFieldValueChanging(app, event)
        
            app.ResultsReadOnlyTextArea_convert.Value='';
        end

        % Button pushed function: CalculateButton_convert
        function CalculateButton_convertPushed(app, event)
            Items = strtrim(app.UnitListBox.Items);
            im = strtrim(app.UnitListBox.Value);
            frequency_unit = find(strcmp(Items, im));
            
            Items = strtrim(app.InputDampingUnitListBox.Items);
            im = strtrim(app.InputDampingUnitListBox.Value);
            damping_unit = find(strcmp(Items, im));

            f=str2num(app.NaturalFrequencyEditField.Value);
            dd=str2num(app.InputDampingValueEditField.Value);
                        
            tpi=2*pi;
            
            if(frequency_unit==1)
                app.fn=f;
                om=tpi*app.fn;
            else
                om=f;
                app.fn=om/tpi;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            
            if(damping_unit==1)  % Q
               app.Q=dd;
            end
            if(damping_unit==2)  % fraction of critical damping
               app.Q=1/(2*dd); 
            end
            if(damping_unit==3)  % loss factor
               app.Q=1/dd; 
            end
            if(damping_unit==4)  % 3 dB Bandwidth delta omega
               app.Q=om/dd;
            end
            if(damping_unit==5)  % 3 dB Bandwidth delta f
               app.Q=app.fn/dd; 
            end
            if(damping_unit==6)  % damping frequency
               app.Q=(om/(4*pi))/dd;    
            end
            if(damping_unit==7)  % decay constant
               app.Q=(om/2)/dd;   
            end
            if(damping_unit==8)  % time_constant
               app.Q=dd*om/2;
            end
            if(damping_unit==9)  % reverberation time
               app.Q=dd*om/13.8; 
            end
            if(damping_unit==10)  % decay rate
               app.Q=4.34*om/dd; 
            end
            if(damping_unit==11)  % logarithmic decrement
               app.Q=pi/dd; 
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            zeta=1/(2*app.Q);
            loss_factor=1/app.Q;
            three_dB_om=om/app.Q;
            three_dB_f=app.fn/app.Q;
            fd=(om/(4*pi))/app.Q;
            sigma=(om/2)/app.Q;
            tau=2*app.Q/om;
            RT60=13.8*app.Q/om;
            D=4.34*om/app.Q;
            log_dec=pi/app.Q;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(damping_unit==1)  % Q
               app.Q=dd; 
            end
            if(damping_unit==2)  % fraction of critical damping
               zeta=dd;     
            end
            if(damping_unit==3)  % loss factor
               loss_factor=dd;     
            end
            if(damping_unit==4)  % 3 dB Bandwidth delta omega
                three_dB_om=dd;
            end
            if(damping_unit==5)  % 3 dB Bandwidth delta f
               three_dB_f=dd; 
            end
            if(damping_unit==6)  % damping frequency
               fd=dd; 
            end
            if(damping_unit==7)  % decay constant
               sigma=dd; 
            end
            if(damping_unit==8)  % time_constant
              tau=dd;  
            end
            if(damping_unit==9)  % reverberation time
               RT60=dd; 
            end
            if(damping_unit==10)  % decay rate
               D=dd; 
            end
            if(damping_unit==11)  % logarithmic decrement
               log_dec=dd; 
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string1=sprintf('quality factor Q=%7.3g',app.Q);
            string2=sprintf('\nfraction of critical damping [zeta]=%7.3g',zeta);
            string3=sprintf('\nloss factor [eta]=%7.3g',loss_factor);
            string4=sprintf('\n\n3 dB Bandwidth [delta omega]=%8.4g rad/sec',three_dB_om);
            string5=sprintf('\n3 dB Bandwidth [delta f]=%8.4g Hz',three_dB_f);
            string6=sprintf('\n\ndamping frequency [fd]=%8.4g Hz',fd);
            string7=sprintf('\ndecay constant [sigma]=%8.4g (1/sec)',sigma);
            string8=sprintf('\n\ntime constant [tau]= %8.4g sec',tau);
            string9=sprintf('\nreverberation time [RT60]=%8.4g sec ',RT60);
            string10=sprintf('\n\ndecay Rate D=%8.4g dB/sec',D);
            string11=sprintf('\nlogarithmic decrement [delta]=%8.4g',log_dec);
            
            big_string=strcat(string1,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);
            big_string=strcat(big_string,string11);
            
            app.ResultsReadOnlyTextArea_convert.Value=big_string;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Callback function
        function CalculateButton_basePushed(app, event)
            PreProcess(app);
        end

        % Callback function
        function CalculateButton_forcePushed(app, event)
            PreProcess(app);
        end

        % Button pushed function: PlotInputButton
        function PlotInputButtonPushed(app, event)
            ClosePlots(app);
            PreProcess(app);
         
            Items = strtrim(app.AmplitudeTypeListBox.Items);
            im = strtrim(app.AmplitudeTypeListBox.Value);
            n = find(strcmp(Items, im));

            figure(1)
            plot(app.THM(:,1),app.THM(:,2))
            xlabel('Frequency (Hz)')
            grid on;

            if(n==1)
                ylabel('Trans (G^2/G^2)');
                title('Power Transmissibility');
            end    
            if(n==2)
                ylabel('Trans (G/G)');
                title('Transmissibility');
            end

            if(n==3)
                ylabel('Accel/Force');               
                title('Frequency Response Function');    
            end     
            if(n==4)
                ylabel('Velocity/Force');   
                title('Frequency Response Function');
            end     
            if(n==5)
                ylabel('Disp/Force');
                title('Frequency Response Function');
            end    

            fmin=str2num(app.MinEditField.Value);
            fmax=str2num(app.MaxEditField.Value);

            if(isempty(fmin) || isempty(fmax))
                fmin=app.THM(1,1);
                fmax=app.THM(end,1);
            end    

            try
                xlim([fmin fmax]);
            catch
            end

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            PreProcess(app);

            Items = strtrim(app.AmplitudeTypeListBox.Items);
            im = strtrim(app.AmplitudeTypeListBox.Value);
            iat = find(strcmp(Items, im));
            
            fmin=str2num(app.MinEditField_cf.Value);
            fmax=str2num(app.MaxEditField_cf.Value);

            nt=str2num(app.NumberofTrialsEditField.Value);
            tol=str2num(app.PeakFrequencyTolerancePercentEditField.Value);
   
            
            fig_num=2;

            
            f=double(app.THM(:,1));        
            a=double(app.THM(:,2));
            
            n=length(f);

            if(iat==1)
                MFRF=@(omega,rho,damp)((1+(2*damp*rho)^2)/((1-rho^2)^2+(2*damp*rho)^2));
            end    
            if(iat==2)
                MFRF=@(omega,rho,damp)(sqrt((1+(2*damp*rho)^2)/((1-rho^2)^2+(2*damp*rho)^2)));      
            end    
            if(iat==3)
                MFRF=@(omega,rho,damp)(sqrt(omega^2/((1-rho^2)^2+(2*damp*rho)^2)));
            end  
            if(iat==4)
                MFRF=@(omega,rho,damp)(sqrt(omega/((1-rho^2)^2+(2*damp*rho)^2))); 
            end      
            if(iat==5)
                MFRF=@(omega,rho,damp)(sqrt(1/((1-rho^2)^2+(2*damp*rho)^2)));
            end            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(fmin<min(f))
                fmin=min(f);
            end
            if(fmax>max(f))
                fmax=max(f);
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            i2=length(f);
            
            for i=1:n
                if(f(i)>=fmin)
                    i1=i;
                    break;
                end
            end
            %
            for i=1:n
                if(f(i)>fmax)
                    i2=i-1;
                    break;
                end
            end
            %
            maxa=0;
            %
            for i=i1:i2
                if(a(i)>maxa)
                    maxa=a(i);
                    maxf=f(i);
                end
            end
            %
            tpi=2*pi;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            error_max=1.0e+90;
            %
            Ar=0;
            fnr=0;
            dampr=0;
  
            tol=tol/100;
            
            flow=maxf*(1-tol);
            fup =maxf*(1+tol);
            
            %
            disp(' ');
            disp('  i      A       fn       damp      Q');
            for i=1:nt
            %    
                if(i<5 || rand()<0.6)
                    A=(0.99+0.02*rand());
                    app.damp=0.5*rand()^1.5;
                    app.fn=(fup-flow)*rand()+flow;
            %
                else
                    if(rand()<0.4)
                          app.fn=fnr*(0.995+0.01*rand());  
                      app.damp=dampr*(0.99+0.02*rand());       
                            A=Ar*(0.995+0.01*rand());
                    else
                        if(rand()<0.5)
                               app.fn=fnr*(0.995+0.01*rand());
                        else
                           app.damp=dampr*(0.995+0.01*rand());                  
                        end
                    end
                end
            %
                if(app.fn<flow || app.fn>fup)
                    app.fn=maxf;
                end
            %
                if(A<0.2 || A>5)
                    A=maxa*(0.8+0.4*rand());
                end
            %
                if(app.damp>0.5)
                    app.damp=0.5*(rand())^2;        
                end
            %
                error=0;
            %
                omegan=tpi*app.fn;
            %
                c=zeros(i2,1);
                for j=i1:i2
                    omega=tpi*f(j);
                    rho=omega/omegan;
                    c(j)=MFRF(omega,rho,app.damp);
                end
            %    
                AX=A*maxa/max(c);
                c=AX*c;
            %
            
            %    aaa=zeros(i2,1);
            %    aaa(i1:i2)=a(i1:i2);
                
            %    ccc=zeros(i2,1);
            %    ccc(i1:i2)=c(i1:i2);    
            
            %    [~,ii]=max(aaa);   
            %    [~,jj]=max(ccc);    
                
                for j=i1:i2
                    bbb=abs(log10(a(j)/c(j)));
                    error=error+bbb;
                end
                
            %    nerr=abs(log2(f(ii)/f(jj)));

            %
                if(error<error_max)
            %        
                    error_max=error;
                         dampr=app.damp;
                          fnr=app.fn;
                           Ar=A;
                          cr=c;
                          app.Q=1/(2*app.damp);
            %
                    out1=sprintf(' %d  %7.3g  %7.3g  %7.3g  %7.3g',i,A,app.fn,app.damp,app.Q);
                    disp(out1);
            %
                    k=1;
            %
                    for j=i1:i2
            %        
                        fr(k)=f(j);
                        ar(k)=cr(j);
                        k=k+1;
            %
                    end
            %
                    Qr=1/(2*dampr);
            %
                    figure(fig_num);
                    plot(f(i1:i2),a(i1:i2),fr,ar);
                    grid on;
                    legend('Measured Data','Curve-fit');
                    xlabel('Frequency (Hz)');
            %
                    if(iat==1)
                        ylabel('Trans (G^2/G^2)');
                        st=sprintf('Power Transmissibility fn=%7.4g Hz  Q=%6.3g',fnr,Qr);
                    end    
                    if(iat==2)
                        ylabel('Trans (G/G)');
                        st=sprintf('Transmissibility fn=%7.4g Hz  Q=%6.3g',fnr,Qr);
                    end
        
                    if(iat==3)
                        ylabel('Accel/Force');               
                        st=sprintf('Frequency Response Function fn=%7.4g Hz  Q=%6.3g',fnr,Qr);    
                    end     
                    if(iat==4)
                        ylabel('Velocity/Force');   
                        st=sprintf('Frequency Response Function fn=%7.4g Hz  Q=%6.3g',fnr,Qr);
                    end     
                    if(iat==5)
                        ylabel('Disp/Force');
                        st=sprintf('Frequency Response Function fn=%7.4g Hz  Q=%6.3g',fnr,Qr);
                    end    
                    title(st);

            %
                end
            %
            end
            %
            disp(' ');
            disp('         Results ');
            disp(' ');
            fprintf('            fn =%7.3g Hz \n damping ratio = %7.3g \n             Q =%7.3g \n',fnr,dampr,Qr);
            %
            
            ss=sprintf(' Natural Frequency \n  = %7.4g Hz \n\n Viscous Damping Ratio \n  = %7.3g \n\n Q = %7.3g',fnr,dampr,Qr);

            app.ResultsReadOnlyTextArea.Value=ss;
        end

        % Value changed function: AmplitudeTypeListBox
        function AmplitudeTypeListBoxValueChanged(app, event)
          
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)

            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changing function: MinEditField_cf
        function MinEditField_cfValueChanging(app, event)
      
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changing function: MaxEditField_cf
        function MaxEditField_cfValueChanging(app, event)
            
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changing function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanging(app, event)
           
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changing function: PeakFrequencyTolerancePercentEditField
        function PeakFrequencyTolerancePercentEditFieldValueChanging(app, event)
           
            app.ResultsReadOnlyTextArea.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DampingFunctionsUIFigure = uifigure('Visible', 'off');
            app.DampingFunctionsUIFigure.Name = 'Damping Functions';
            app.ReturnButton = uibutton(app.DampingFunctionsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.damping_functions_aver11byTomIrvineLabel = uilabel(app.DampingFunctionsUIFigure);
            app.damping_functions_aver11byTomIrvineLabel.Text = 'damping_functions_a    ver 1.1  by Tom Irvine';
            app.TabGroup = uitabgroup(app.DampingFunctionsUIFigure);
            app.HalfPowerBandwidthExtensionTab = uitab(app.TabGroup);
            app.HalfPowerBandwidthExtensionTab.Title = 'Half Power Bandwidth Extension';
            app.TabGroup2 = uitabgroup(app.HalfPowerBandwidthExtensionTab);
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'This method performs a curve-fit to determine the damping ratio for a system excited by base excitation or an applied force. '; ''; 'It is an extension of the half-power bandwidth method. The input data must be a frequency response function. '; ' '; 'The input file should have two columns: freq (Hz) & amplitude '; ''};
            app.InputDataTab = uitab(app.TabGroup2);
            app.InputDataTab.Title = 'Input Data';
            app.UpdateListButton = uibutton(app.InputDataTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.listbox_method = uilistbox(app.InputDataTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputDataTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.AmplitudeTypeListBoxLabel = uilabel(app.InputDataTab);
            app.AmplitudeTypeListBoxLabel.Text = 'Amplitude Type';
            app.AmplitudeTypeListBox = uilistbox(app.InputDataTab);
            app.AmplitudeTypeListBox.Items = {'Base Excitation:  Power Transmissibility (G^2/G^2)', 'Base Excitation: Transmissibility (G/G)', 'Applied Force:  Acceleration/Force', 'Applied Force:  Velocity/Force', 'Applied Force:  Displacement/Force'};
            app.AmplitudeTypeListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeTypeListBoxValueChanged, true);
            app.AmplitudeTypeListBox.Value = 'Base Excitation:  Power Transmissibility (G^2/G^2)';
            app.PlotFrequenciesHzLabel = uilabel(app.InputDataTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.InputDataTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.InputDataTab, 'text');
            app.MaxEditFieldLabel = uilabel(app.InputDataTab);
            app.MaxEditFieldLabel.Text = 'Max ';
            app.MaxEditField = uieditfield(app.InputDataTab, 'text');
            app.PlotInputButton = uibutton(app.InputDataTab, 'push');
            app.PlotInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputButtonPushed, true);
            app.PlotInputButton.Text = 'Plot Input';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputDataTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputDataTab);
            app.SelectInputArrayListBox.Items = {''};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.SelectInputArrayListBox.Value = '';
            app.CalculationTab = uitab(app.TabGroup2);
            app.CalculationTab.Title = 'Calculation';
            app.ResultsReadOnlyTextAreaLabel_3 = uilabel(app.CalculationTab);
            app.ResultsReadOnlyTextAreaLabel_3.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea = uitextarea(app.CalculationTab);
            app.CalculateButton = uibutton(app.CalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CurveFitFrequenciesHzLabel = uilabel(app.CalculationTab);
            app.CurveFitFrequenciesHzLabel.Text = 'Curve-Fit Frequencies (Hz)';
            app.MinEditField_2Label = uilabel(app.CalculationTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_cf = uieditfield(app.CalculationTab, 'text');
            app.MinEditField_cf.ValueChangingFcn = createCallbackFcn(app, @MinEditField_cfValueChanging, true);
            app.MaxEditField_2Label = uilabel(app.CalculationTab);
            app.MaxEditField_2Label.Text = 'Max ';
            app.MaxEditField_cf = uieditfield(app.CalculationTab, 'text');
            app.MaxEditField_cf.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_cfValueChanging, true);
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculationTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculationTab, 'text');
            app.NumberofTrialsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanging, true);
            app.NumberofTrialsEditField.Value = '10000';
            app.PeakFrequencyTolerancePercentEditFieldLabel = uilabel(app.CalculationTab);
            app.PeakFrequencyTolerancePercentEditFieldLabel.Text = {'Peak Frequency'; 'Tolerance Percent'};
            app.PeakFrequencyTolerancePercentEditField = uieditfield(app.CalculationTab, 'text');
            app.PeakFrequencyTolerancePercentEditField.ValueChangingFcn = createCallbackFcn(app, @PeakFrequencyTolerancePercentEditFieldValueChanging, true);
            app.PeakFrequencyTolerancePercentEditField.Value = '0.5';
            app.LogDecrementMethodTab = uitab(app.TabGroup);
            app.LogDecrementMethodTab.Title = 'Log Decrement Method';
            app.CalculateButton_log = uibutton(app.LogDecrementMethodTab, 'push');
            app.CalculateButton_log.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_logPushed, true);
            app.CalculateButton_log.Text = 'Calculate';
            app.NumberofPeriodsnEditFieldLabel = uilabel(app.LogDecrementMethodTab);
            app.NumberofPeriodsnEditFieldLabel.Text = 'Number of Periods n';
            app.NumberofPeriodsnEditField = uieditfield(app.LogDecrementMethodTab, 'text');
            app.NumberofPeriodsnEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofPeriodsnEditFieldValueChanging, true);
            app.Peak0EditFieldLabel = uilabel(app.LogDecrementMethodTab);
            app.Peak0EditFieldLabel.Text = 'Peak 0';
            app.Peak0EditField = uieditfield(app.LogDecrementMethodTab, 'text');
            app.Peak0EditField.ValueChangingFcn = createCallbackFcn(app, @Peak0EditFieldValueChanging, true);
            app.PeaknEditFieldLabel = uilabel(app.LogDecrementMethodTab);
            app.PeaknEditFieldLabel.Text = 'Peak n';
            app.PeaknEditField = uieditfield(app.LogDecrementMethodTab, 'text');
            app.PeaknEditField.ValueChangingFcn = createCallbackFcn(app, @PeaknEditFieldValueChanging, true);
            app.LogdecrementmethodforestimatingdampingLabel = uilabel(app.LogDecrementMethodTab);
            app.LogdecrementmethodforestimatingdampingLabel.Text = 'Log decrement method for estimating damping.';
            app.ResultsReadOnlyTextAreaLabel = uilabel(app.LogDecrementMethodTab);
            app.ResultsReadOnlyTextAreaLabel.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_log = uitextarea(app.LogDecrementMethodTab);
            app.DampingValueConversionTab = uitab(app.TabGroup);
            app.DampingValueConversionTab.Title = 'Damping Value Conversion';
            app.CalculateButton_convert = uibutton(app.DampingValueConversionTab, 'push');
            app.CalculateButton_convert.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_convertPushed, true);
            app.CalculateButton_convert.Text = 'Convert';
            app.NaturalFrequencyEditFieldLabel = uilabel(app.DampingValueConversionTab);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyEditField = uieditfield(app.DampingValueConversionTab, 'text');
            app.NaturalFrequencyEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyEditFieldValueChanging, true);
            app.NaturalFrequencyEditField.Value = '100';
            app.UnitListBox = uilistbox(app.DampingValueConversionTab);
            app.UnitListBox.Items = {'Hz', 'rad/sec'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'Hz';
            app.ResultsReadOnlyTextAreaLabel_2 = uilabel(app.DampingValueConversionTab);
            app.ResultsReadOnlyTextAreaLabel_2.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_convert = uitextarea(app.DampingValueConversionTab);
            app.InputDampingValueEditFieldLabel = uilabel(app.DampingValueConversionTab);
            app.InputDampingValueEditFieldLabel.Text = 'Input Damping Value';
            app.InputDampingValueEditField = uieditfield(app.DampingValueConversionTab, 'text');
            app.InputDampingValueEditField.ValueChangingFcn = createCallbackFcn(app, @InputDampingValueEditFieldValueChanging, true);
            app.InputDampingUnitListBoxLabel = uilabel(app.DampingValueConversionTab);
            app.InputDampingUnitListBoxLabel.Text = 'Input Damping Unit';
            app.InputDampingUnitListBox = uilistbox(app.DampingValueConversionTab);
            app.InputDampingUnitListBox.Items = {'quality factor Q', 'fraction of critical damping [zeta]', 'loss factor [eta]', '3 dB Bandwidth [delta omega (rad/sec)]  ', '3 dB Bandwidth [delta f (Hz)]', 'damping frequency [fd (Hz)] ', 'decay constant [sigma (1/sec)]  ', 'time constant [tau (sec)]', 'reverberation time [RT60 (sec)]', 'decay Rate D (dB/sec)', 'logarithmic decrement [delta]'};
            app.InputDampingUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InputDampingUnitListBoxValueChanged, true);
            app.InputDampingUnitListBox.Value = 'quality factor Q';
            app.SomeconversionpairsdependonnaturalfrequencyOthersdonotLabel = uilabel(app.DampingValueConversionTab);
            app.SomeconversionpairsdependonnaturalfrequencyOthersdonotLabel.Text = {'Some conversion pairs depend on '; 'natural frequency.   Others do not.'};
            % Show the figure after all components are created
            app.DampingFunctionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = damping_functions_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DampingFunctionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DampingFunctionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DampingFunctionsUIFigure)
        end
    end
end