classdef dboct_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dBCalculationsUIFigure          matlab.ui.Figure
        ResultsTextArea_2               matlab.ui.control.TextArea
        ResultsTextArea_2Label          matlab.ui.control.Label
        PerformAnalysisButton           matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        aTab                            matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        SRSGEditField                   matlab.ui.control.EditField
        SRSGEditFieldLabel              matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        NewCoordinateFrequencyHzEditField  matlab.ui.control.EditField
        NewCoordinateFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SlopedBoctfromlowertohigherfrequencyEditField  matlab.ui.control.EditField
        SlopedBoctfromlowertohigherfrequencyLabel  matlab.ui.control.Label
        AnalysisListBox_2               matlab.ui.control.ListBox
        AnalysisListBox_2Label          matlab.ui.control.Label
        DimensionListBox_2              matlab.ui.control.ListBox
        DimensionListBox_2Label         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        bTab                            matlab.ui.container.Tab
        Point2SRSGEditField             matlab.ui.control.EditField
        Point2SRSGEditFieldLabel        matlab.ui.control.Label
        Point1SRSGEditField             matlab.ui.control.EditField
        Point1SRSGEditFieldLabel        matlab.ui.control.Label
        AnalysisListBox                 matlab.ui.control.ListBox
        AnalysisListBoxLabel            matlab.ui.control.Label
        DimensionListBox                matlab.ui.control.ListBox
        DimensionListBoxLabel           matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.EditField
        ResultEditFieldLabel            matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        cTab                            matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        CalculateButton_3               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NumberofLevelsListBox           matlab.ui.control.ListBox
        NumberofLevelsListBoxLabel      matlab.ui.control.Label
        dTab                            matlab.ui.container.Tab
        MaxEditField_X                  matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField_X                  matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        DomainLimitsListBox_X           matlab.ui.control.ListBox
        DomainLimitsLabel               matlab.ui.control.Label
        OutputPlotTitleEditField        matlab.ui.control.EditField
        OutputPlotTitleEditFieldLabel   matlab.ui.control.Label
        ExportNameEditField             matlab.ui.control.EditField
        ExportNameEditFieldLabel        matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        FunctionTypeListBox             matlab.ui.control.ListBox
        FunctionTypeListBoxLabel        matlab.ui.control.Label
        CalculateButton_4               matlab.ui.control.Button
        dBMarginEditField               matlab.ui.control.EditField
        dBMarginEditFieldLabel          matlab.ui.control.Label
        eTab                            matlab.ui.container.Tab
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel_2             matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel_2             matlab.ui.control.Label
        PlotdBLimitsListBox             matlab.ui.control.ListBox
        PlotdBLimitsLabel               matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        PlotTitleEditField              matlab.ui.control.EditField
        PlotTitleEditFieldLabel         matlab.ui.control.Label
        SelectResponseInputArrayListBox  matlab.ui.control.ListBox
        SelectResponseInputArrayListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox_2       matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_2              matlab.ui.control.Button
        text1_3                         matlab.ui.control.Label
        ListboxFormat                   matlab.ui.control.ListBox
        text1_2                         matlab.ui.control.Label
        ListboxMethod                   matlab.ui.control.ListBox
        FunctionsListBox                matlab.ui.control.ListBox
        FunctionsListBoxLabel           matlab.ui.control.Label
        CalculateButton_5               matlab.ui.control.Button
        fTab                            matlab.ui.container.Tab
        ExportNameEditField_2           matlab.ui.control.EditField
        ExportNameEditField_2Label      matlab.ui.control.Label
        PlotTitleEditField_2            matlab.ui.control.EditField
        PlotTitleLabel                  matlab.ui.control.Label
        CalculateButton_kd              matlab.ui.control.Button
        UITable_kd                      matlab.ui.control.Table
        NumberofFrequenciesListBox      matlab.ui.control.ListBox
        NumberofFrequenciesListBoxLabel  matlab.ui.control.Label
        PlotFrequenciesLabel            matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        YAxisLabelEditField             matlab.ui.control.EditField
        YAxisLabelEditFieldLabel        matlab.ui.control.Label
        AmplitudeTypeListBox            matlab.ui.control.ListBox
        AmplitudeTypeListBoxLabel       matlab.ui.control.Label
        SelectInputArrayListBox_kd      matlab.ui.control.ListBox
        SelectInputArrayListBox_3Label  matlab.ui.control.Label
        UpdateListButton_kd             matlab.ui.control.Button
        text1_4                         matlab.ui.control.Label
        listbox_method_kd               matlab.ui.control.ListBox
        dboct_aver10byTomIrvineLabel    matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        nDimension     %
        nAnalysis      %
        nDimension_Int %
        nAnalysis_Int  %   
        THM            %  
        nx             %  
        orig           %
        nPlotdB        %
        nFormat        % 
        nMethod        %
        nBands         %
    end
    
    methods (Access = private)

        function BandChange(app)
            Clear(app);
            app.nBands = str2num(app.NumberofFrequenciesListBox.Value);
           
            for i = 1:app.nBands
               for j=1:3
                  data_s{i,j} = '';     
               end     
            end
 
            app.UITable_kd.Data=data_s; 
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_kd,s) % adjust the style           
        end

        function read_data(app)
        
            ClosePlots(app);       

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            if(n==1)

                if(m==1)
                    try
                        FS=app.SelectInputArrayListBox_2.Value;
                        app.THM=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end
                else

                    try
                        FS=app.SelectInputArrayListBox_2.Value;
                        F1=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end

                    try
                        FS=app.SelectResponseInputArrayListBox.Value;
                        F2=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end                    
   
                    try
                        app.THM=[F1 F2(:,2)];
                    catch
                        size(F1)
                        size(F2)
                        warndlg('Arrays not compatible');
                        return;
                    end    

                end

            else
                if(m==1)
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       app.THM = fscanf(fid,'%g %g %g',[2 inf]);
                       app.THM=app.THM';
                
                       size(app.THM)
                       msgbox('File read');              
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end
                else    
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F1 = fscanf(fid,'%g %g',[2 inf]);
                       F1=F1';
                
                       sz1=size(F1);
    
                       msgbox('File 1 read'); 

                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F2 = fscanf(fid,'%g %g',[2 inf]);
                       F2=F2';
                
                       sz2=size(F2);
    
                       msgbox('File 2 read');

                       if(sz1(1)~=sz2(1))
                            warndlg('File 1 & File 2 have different lengths')
                            return;
                       else    
                            app.THM=[F1 F2(:,2)];
                       end     
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end                
                end
            end           
        end    

        function external_array(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end

        end

        function dB_plot_limits(app)
            
            Items = char(app.PlotdBLimitsListBox.Items);
            im=app.PlotdBLimitsListBox.Value;
            [app.nPlotdB]=find_index(Items,im);   

            if(app.nPlotdB==1)
                app.MaxEditField.Visible='off';
                app.MinEditField.Visible='off';   
                app.MaxEditFieldLabel_2.Visible='off';
                app.MinEditFieldLabel_2.Visible='off';                
            else
                app.MaxEditField.Visible='on';
                app.MinEditField.Visible='on';   
                app.MaxEditFieldLabel_2.Visible='on';
                app.MinEditFieldLabel_2.Visible='on';              
            end
           
        end

        function update_two_functions(app)

            cell_array=update_psd_srs_spl_fds_23();   

            app.SelectInputArrayListBox_2.Items=unique(sort(cell_array)); 
            app.SelectResponseInputArrayListBox.Items=unique(sort(cell_array));             
        
        end    

        function Trans_change(app)

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            app.SelectInputArrayListBox.Visible='off';
            app.SelectInputArrayListBoxLabel.Visible='off';   
            app.SelectResponseInputArrayListBox.Visible='off';
            app.SelectResponseInputArrayListBoxLabel.Visible='off';  

            if(n==2)
                external_array(app);
            end    

            app.SelectInputArrayListBox.Visible='on';
            app.SelectInputArrayListBoxLabel.Visible='on'; 
                
            if(m==1)
                ss=sprintf('Select Common Input Array');
            else
                ss=sprintf('Select Base Input Array');
                app.SelectResponseInputArrayListBox.Visible='on';
                app.SelectResponseInputArrayListBoxLabel.Visible='on';                
            end

            app.SelectInputArrayListBoxLabel.Text=ss; 
            update(app);

            app.ListboxMethod.Value=app.ListboxMethod.Items{1};

            update_two_functions(app);

        end                

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end  

        function ChangeLimits(app)
            Items = char(app.DomainLimitsListBox_X.Items);
            im=app.DomainLimitsListBox_X.Value;
            [app.nx]=find_index(Items,im);

            if(app.nx==1)
                app.MinEditField_X.Enable='off';
                app.MinEditField_X.Value=' ';
                app.MaxEditField_X.Enable='off';
                app.MaxEditField_X.Value=' ';             
            else
                app.MinEditField_X.Enable='on';
                app.MaxEditField_X.Enable='on';     
            end
        end    

        function update(app)
            cell_array=update_psd_srs_spl();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
        end    
        function update_kd(app)
            cell_array=update_psd_srs_spl();
            app.SelectInputArrayListBox_kd.Items=unique(sort(cell_array)); 
            app.listbox_method_kd.Value=app.listbox_method_kd.Items{1};    
        end            

        function ChangeInterpolation(app)
            Clear(app);

            Items = char(app.DimensionListBox_2.Items);
            im=app.DimensionListBox_2.Value;
            [app.nDimension_Int]=find_index(Items,im);

            Items = char(app.AnalysisListBox_2.Items);
            im=app.AnalysisListBox_2.Value;
            [app.nAnalysis_Int]=find_index(Items,im);            

            m=app.nAnalysis_Int;
            n=app.nDimension_Int;
            
            if(n==1)
                app.SRSGEditFieldLabel.Text='SRS(G)';
            else
                app.SRSGEditFieldLabel.Text='PSD(G^2/Hz)';
            end

            if(m==1 || m==3)
                app.Label_2.Visible='on';
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
            else
                app.Label_2.Visible='off';
                app.EditField_3.Visible='off';
                app.EditField_4.Visible='off';
            end
            
            if(m==2)
                app.SlopedBoctfromlowertohigherfrequencyEditField.Visible='on';
                app.SlopedBoctfromlowertohigherfrequencyLabel.Visible='on';   
            else
                app.SlopedBoctfromlowertohigherfrequencyEditField.Visible='off';
                app.SlopedBoctfromlowertohigherfrequencyLabel.Visible='off';     
            end
            
            if(m==2 || m==3)
                app.NewCoordinateFrequencyHzEditField.Visible='on';
                app.NewCoordinateFrequencyHzEditFieldLabel.Visible='on';   
            else
                app.NewCoordinateFrequencyHzEditField.Visible='off';
                app.NewCoordinateFrequencyHzEditFieldLabel.Visible='off'; 
            end

        end    
        
        function LB_change(app)
            Clear(app);
            N = str2num(app.NumberofLevelsListBox.Value);
           
            for i = 1:N
               for j=1:1
                  data_s{i,j} = '';     
               end     
            end
 
            app.UITable.Data=data_s; 
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style
            
        end    
        function Clear(app)
            ClosePlots(app);
            app.ResultEditField.Visible='off';
            app.ResultEditFieldLabel.Visible='off';         
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.ResultsTextArea_2.Visible='of';
            app.ResultsTextArea_2Label.Visible='off';
        end    
        function AnalysisChange(app)
            Clear(app);
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);
            app.TabGroup.SelectedTab=app.TabGroup.Children(n);
        end    
        function TextChange(app)
            Items = char(app.DimensionListBox.Items);
            im=app.DimensionListBox.Value;
            [app.nDimension]=find_index(Items,im);

            Items = char(app.AnalysisListBox.Items);
            im=app.AnalysisListBox.Value;
            [app.nAnalysis]=find_index(Items,im);

            if(app.nDimension==1)
                app.Point1SRSGEditFieldLabel.Text=sprintf('Point 1 SRS(G)');
            end
            if(app.nDimension==2)
                app.Point1SRSGEditFieldLabel.Text=sprintf('Point 1 (GRMS)');
            end
            if(app.nDimension==3)
                app.Point1SRSGEditFieldLabel.Text=sprintf('Point 1 PSD(G^2/Hz)');
            end
            
            if(app.nAnalysis==1)
                if(app.nDimension==1)
                    app.Point2SRSGEditFieldLabel.Text=sprintf('Point 2 SRS(G)');
                end
                if(app.nDimension==2)
                    app.Point2SRSGEditFieldLabel.Text=sprintf('Point 2 (GRMS)');
                end
                if(app.nDimension==3)
                    app.Point2SRSGEditFieldLabel.Text=sprintf('Point 2 PSD(G^2/Hz)');
                end
            else
                app.Point2SRSGEditFieldLabel.Text=sprintf('dB Difference');    
            end    

        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AnalysisChange(app);
            TextChange(app);
            Clear(app);
            LB_change(app);
            ChangeInterpolation(app);
            update(app);
            update_kd(app);
            ChangeLimits(app);
            Trans_change(app);
            dB_plot_limits(app);
            BandChange(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            AnalysisChange(app);

            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                app.TextChange(app);
            end    
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
            AnalysisChange(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            Clear(app);

            m=app.TabGroup.SelectedTab;
            n=m.Title;

            if(strcmp(n,'a'))
                n=1;
            end              
            if(strcmp(n,'b'))
                n=2;
            end     
            if(strcmp(n,'c'))
                n=3;
            end  
            if(strcmp(n,'d'))
                n=4;
            end               
            if(strcmp(n,'e'))
                n=5;
            end               
            if(strcmp(n,'f'))
                n=6;
            end         

            app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
            % app.listbox_choice=n;
        end

        % Value changed function: DimensionListBox
        function DimensionListBoxValueChanged(app, event)
            TextChange(app);
            Clear(app);
        end

        % Value changed function: AnalysisListBox
        function AnalysisListBoxValueChanged(app, event)
            TextChange(app);
            Clear(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            % nDimension     
            % nAnalysis

            Clear(app);

            A=str2num(app.Point1SRSGEditField.Value);
            B=str2num(app.Point2SRSGEditField.Value);

            n=app.nDimension;
            m=app.nAnalysis;
            
            if(m==1)
               if(n==1 || n==2)
                   C=20*log10(B/A);
               else
                   C=10*log10(B/A);
               end
               out1=sprintf('%8.3g dB',C);
            else
               if(n==1 || n==2)
                   C=A*10^(B/20);
                   if(n==1)
                      out1=sprintf('%8.3g G',C);
                   else
                      out1=sprintf('%8.3g GRMS',C);                       
                   end
               end
               if(n==3)
                   C=A*10^(B/10);
                   out1=sprintf('%8.3g G^2/Hz',C);       
               end    
            end    
            
            app.ResultEditField.Value=out1;
            app.ResultEditField.Visible='on';
            app.ResultEditFieldLabel.Visible='on';

        end

        % Value changing function: Point1SRSGEditField
        function Point1SRSGEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: Point2SRSGEditField
        function Point2SRSGEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Clear(app);

            n=app.nDimension_Int;
            m=app.nAnalysis_Int;
            
              f1=str2num(app.FrequencyHzEditField.Value);
            amp1=str2num(app.SRSGEditField.Value);
            
            if(m==2 || m==3)
                new_frequency=str2num(app.NewCoordinateFrequencyHzEditField.Value);
            end
            
            if(m==1 || m==3)
            %
              f2=str2num(app.EditField_3.Value);
            amp2=str2num(app.EditField_4.Value);
            %
                 noct=log(f2/f1)/log(2);
                 nslope=log(amp2/amp1)/log(f2/f1);     
            %
               if(n==1)
                  dbpo=20*nslope*log10(2); 
               else        
                  dbpo=10*nslope*log10(2);        
               end
            %
               dbchange=noct*dbpo;
            %
            end 
            if(m==2)
            %
               dbpo=str2num(app.SlopedBoctfromlowertohigherfrequencyEditField.Value);
            %
               if(n==1)
                  nslope=(dbpo/20)/log10(2);
               else
                  nslope=(dbpo/10)/log10(2);      
               end
            %
               y=amp1*(new_frequency/f1)^(nslope); 
               if(n==1)
                  s2=sprintf('%8.4g Hz, %8.4g G',new_frequency,y);
               else
                  s2=sprintf('%8.4g Hz, %8.4g G^2/Hz',new_frequency,y);       
               end
            %
               noct=log(new_frequency/f1)/log(2);
               dbchange=noct*dbpo;
            %    
            end  
            if(m==3)
               y=amp1*(new_frequency/f1)^(nslope); 
               if(n==1)
                  pv=y*386/(2*pi*new_frequency);
                  s2=sprintf('%8.4g Hz, %8.4g G, %8.4g in/sec',new_frequency,y,pv);
               else
                  s2=sprintf('%8.4g Hz, %8.4g G^2/Hz',new_frequency,y);       
               end
            end  
            
            ss=sprintf(' %7.3g dB/oct\n %7.3g log-log slope \n\n %7.3g octave span \n %7.3g dB Change',dbpo,nslope,noct,dbchange);
            
            if(m==2 || m==3)
                ss=sprintf(' %s \n\n %s ',ss,s2);
            end

            app.ResultsTextArea_2.Value=ss;
            app.ResultsTextArea_2.Visible='on';
            app.ResultsTextArea_2Label.Visible='on';

        end

        % Value changed function: NumberofLevelsListBox
        function NumberofLevelsListBoxValueChanged(app, event)
            LB_change(app);
            Clear(app);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
          
            Clear(app);

            %  http://www.sengpielaudio.com/calculator-leveladding.htm

            N = str2num(app.NumberofLevelsListBox.Value);
            
            A=char(app.UITable.Data);
            
            B=str2num(A);

            sum_coh=0;
            sum_non=0;

            R10=10;
            R20=20;

            for i=1:N
                B(i)
                sum_coh=sum_coh+10^(B(i)/R20);
                sum_non=sum_non+10^(B(i)/R10);               
            end

            sum_coh=R20*log10(sum_coh);
            sum_non=R10*log10(sum_non);    

            ss=sprintf('Overall Levels \n\n   Coherent %6.1f dB \n Incoherent %6.1f dB \n',sum_coh,sum_non);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            Clear(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            Clear(app);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            Clear(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            Clear(app);
        end

        % Value changed function: DimensionListBox_2
        function DimensionListBox_2ValueChanged(app, event)
            ChangeInterpolation(app);
        end

        % Value changed function: AnalysisListBox_2
        function AnalysisListBox_2ValueChanged(app, event)
             ChangeInterpolation(app);
        end

        % Value changing function: 
        % SlopedBoctfromlowertohigherfrequencyEditField
        function SlopedBoctfromlowertohigherfrequencyEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: NewCoordinateFrequencyHzEditField
        function NewCoordinateFrequencyHzEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: SRSGEditField
        function SRSGEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: EditField_4
        function EditField_4ValueChanging(app, event)
            Clear(app);
        end

        % Value changed function: FunctionTypeListBox
        function FunctionTypeListBoxValueChanged(app, event)
            Clear(app);
        end

        % Value changing function: dBMarginEditField
        function dBMarginEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            Clear(app);

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            app.orig=app.THM;    

            clear n;

            Items = char(app.FunctionTypeListBox.Items);
            im=app.FunctionTypeListBox.Value;
            [ntype]=find_index(Items,im);

            t_string=app.OutputPlotTitleEditField.Value;

            dB=str2num(app.dBMarginEditField.Value);


            if(ntype==1) % psd
               scale=10^(dB/10);
               app.THM(:,2)=app.THM(:,2)*scale;
               x_label='Frequency (Hz)';
               y_label='Accel (G^2/Hz)';

               [~,grms_in] = calculate_PSD_slopes(app.orig(:,1),app.orig(:,2));
               [~,grms_out] = calculate_PSD_slopes(app.THM(:,1),app.THM(:,2));

                leg1=sprintf('Input, %6.3g GRMS',grms_in);
                
                if(dB>0)
                    leg2=sprintf('+%g dB, %6.3g GRMS',dB,grms_out);
                else
                    leg2= sprintf('%g dB, %6.3g GRMS',dB,grms_out);    
                end

            end   
            if(ntype==2)         % srs
                scale=10^(dB/20);
                app.THM(:,2)=app.THM(:,2)*scale;
                x_label='Natural Frequency (Hz)';  
                y_label='Peak Accel (G)';  
                leg1='Input';
                if(dB>0)
                    leg2=sprintf('+%g dB',dB);
                else
                    leg2= sprintf('%g dB',dB);    
                end 
            end
            if(ntype==3)
               app.THM(:,2)=app.THM(:,2)+dB;
            end

            % plot

            if(app.nx==1 ) % automatic
                fmin=app.orig(1,1);
                fmax=app.orig(end,1);
            else    % manual
                fmin=str2num(app.MinEditField_X.Value);
                fmax=str2num(app.MaxEditField_X.Value);   
            end 

            fig_num=1;
            md=6;

            ppp1=app.orig;
            ppp2=app.THM;

            if(ntype<=2)
                if(dB<0)
    
                    [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                        y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md); 
                else
        
                    [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                        y_label,t_string,ppp2,ppp1,leg2,leg1,fmin,fmax,md);    
                end
            end
            if(ntype==3)
                oadb=10*log10(sum(10.^(app.THM(:,2)/10)));
                [~,n_type]=octave_test(app.THM);   
                ttt=t_string;
                if(n_type==3)
                    t_string=sprintf(' One-Third Octave Sound Pressure Level, %s\n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                else
                    t_string=sprintf(' Full Octave Sound Pressure Level, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);    
                end
                data=app.THM;
                
                xlab='Center Frequency (Hz)';
                ylab='SPL (dB)';
    
                ng=2;

                if(n_type==3)
                    [fig_num]=plot_one_onethird_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);
                else
                    [fig_num]=plot_one_one_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);       
                end 

                %%%

                figure(fig_num);
                
                plot(app.THM(:,1),app.THM(:,2));
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
                
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);     
                end
                
                dB=app.THM(:,2);
            
                [ymin,ymax]=dB_ylimits(dB);
                
                ylim([ymin,ymax]);    
                
                title(t_string)
                xlabel('Center Frequency (Hz)');
                ylabel('SPL (dB)');

                grid on;
                set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','log','YScale','lin');

                %%%
                              
            end    

            try
                name=app.ExportNameEditField.Value;
                assignin('base',name,app.THM);
            catch
            end

        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: DomainLimitsListBox_X
        function DomainLimitsListBox_XValueChanged(app, event)
            ChangeLimits(app);
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            Clear(app);

            fig_num=1;

            Items = char(app.FunctionsListBox.Items);
            im=app.FunctionsListBox.Value;
            [nfunction]=find_index(Items,im);
           
            x_label='Natural Frequency (Hz)';
            
            if(nfunction==1) % SRS (G)
                b=1;
            end
            if(nfunction==2) % PSD (G^2/Hz)
                b=2;
                x_label='Frequency (Hz)';
            end
            if(nfunction==3) % FDS (G^4)
                b=4;
            end
            if(nfunction==4) % FDS (G^8);
                b=8;
            end

            %%%%

            read_data(app); 

            app.THM

            THM1=[app.THM(:,1) app.THM(:,2)];
            THM2=[app.THM(:,1) app.THM(:,3)];

            %%%%

            sz1=size(THM1);
            sz2=size(THM2);
            
            n1=sz1(1);
            n2=sz2(1);
            
            % if(n1~=n2)
            %    warndlg('Functions have different lengths');
            %    return;
            % end
            if(THM1(1,1)~=THM2(1,1))
                warndlg('Functions have different starting frequencies');
                return;
            end
            % if(THM1(end,1)~=THM2(end,1))
            %    warndlg('Functions have different end frequencies');
            %    return;
            % end
                        
            nnn=min([n1 n2]);
            
            dB_difference=zeros(nnn,2);
            
            for i=1:nnn
               
                if(THM1(i,1)>0)
                    ddd=(THM1(i,1)-THM2(i,1))/THM1(i,1);
                
                    if(abs(ddd)>0.01)
                        warndlg('Functions have different frequency steps');
                        return;        
                    end
                end
                
                ratio=THM1(i,2)/THM2(i,2);
                
                if(nfunction>=2)
                    ratio=(THM1(i,2)/THM2(i,2))^(1/b);
                end
                
                dB_difference(i,1)=THM1(i,1);
                dB_difference(i,2)=20*log10(ratio);
                
            %    out1=sprintf('%8.4g %8.4g %8.4g %g %8.4g ',dB_difference(i,2),THM1(i,2),THM2(i,2),b,ratio,dB_difference(i,2));
            %    disp(out1);
                
            end
            
            fmin=dB_difference(1,1);
            fmax=dB_difference(end,1);
            ppp=dB_difference;
            
            y_label='Difference (dB)';
            
            t_string=app.PlotTitleEditField.Value;
            
            [fig_num,h2]=...
                plot_loglin_function_h2_yfree(fig_num,x_label,y_label,t_string,ppp,fmin,fmax);
            
            if(app.nPlotdB==2)
                ymax=str2num(app.MaxEditField.Value);
                ymin=str2num(app.MinEditField.Value);
                
                if(ymin>ymax)
                    temp=ymax;
                    ymax=ymin;
                    ymin=temp;
                    app.MaxEditField.Value=sprintf('%7.3g',ymax);
                    app.MinEditField.Value=sprintf('%7.3g',ymin);              
                end
                ylim([ymin ymax]);
            end
            
            try
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name,dB_difference);
            catch
            end

        end

        % Value changed function: FunctionsListBox
        function FunctionsListBoxValueChanged(app, event)
            Clear(app);
        end

        % Value changed function: ListboxMethod
        function ListboxMethodValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat
        function ListboxFormatValueChanged(app, event)
            Trans_change(app);
        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed(app, event)
            update_two_functions(app);
        end

        % Value changed function: PlotdBLimitsListBox
        function PlotdBLimitsListBoxValueChanged(app, event)
            dB_plot_limits(app);
        end

        % Button pushed function: UpdateListButton_kd
        function UpdateListButton_kdPushed(app, event)
            update_kd(app);
        end

        % Value changed function: listbox_method_kd
        function listbox_method_kdValueChanged(app, event)
            Items = char(app.listbox_method_kd.Items);
            im=app.listbox_method_kd.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_kd(app);
               app.SelectInputArrayListBox_kd.Value=name;
            end    
        end

        % Value changed function: NumberofFrequenciesListBox
        function NumberofFrequenciesListBoxValueChanged(app, event)
            BandChange(app);
        end

        % Button pushed function: CalculateButton_kd
        function CalculateButton_kdPushed(app, event)
            Clear(app);

            fig_num=1;

            try
                Items = char(app.listbox_method_kd.Items);
                im=app.listbox_method_kd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            if(n==1)
                try
                     FS=app.SelectInputArrayListBox_kd.Value;
                     app.THM=evalin('base',FS);
                catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                end
            end 

            fmin=str2num(app.StartEditField.Value);
            fmax=str2num(app.EndEditField.Value);           

            y_label=app.YAxisLabelEditField.Value;

            Items = char(app.AmplitudeTypeListBox.Items);
            im=app.AmplitudeTypeListBox.Value;
            [at]=find_index(Items,im);        

            t_string=app.PlotTitleEditField_2.Value;

            %%%

            A=char(app.UITable_kd.Data);            
            B=str2num(A);

            N=app.nBands;

            f1=B(1:N);
            f2=B((N+1):(2*N));
            dB=B((2*N+1):end);

            %%%

            sz=size(app.THM);
            f=app.THM(:,1);
    
            num=sz(1);
    
            aaa=app.THM;

            for j=1:num
                
                for k=1:N
                    if(f(j)>=f1(k) && f(j)<=f2(k))
                        if(at==1)
                            aaa(j,2)=app.THM(j,2)-dB(k);
                        end
                        if(at==2)
                            aaa(j,2)=app.THM(j,2)*10^(-dB(k)/10);
                        end
                        if(at==3)
                            aaa(j,2)=app.THM(j,2)*10^(-dB(k)/20);                
                        end
                        break;
                    end
                end
           
            end

            %%%

            x_label='Frequency (Hz)';
            ppp=aaa;
    
            if(at==1)
                [fig_num,~]=...
                plot_loglin_function_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax);
            end
            if(at>=2)
                [fig_num,~]=...
                plot_loglog_function_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax);        
            end

            try
                name=app.ExportNameEditField_2.Value;
                assignin('base',name,aaa);
            catch
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dBCalculationsUIFigure = uifigure('Visible', 'off');
            app.dBCalculationsUIFigure.Name = 'dB Calculations';
            app.ReturnButton = uibutton(app.dBCalculationsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.dboct_aver10byTomIrvineLabel = uilabel(app.dBCalculationsUIFigure);
            app.dboct_aver10byTomIrvineLabel.Text = 'dboct_a      ver 1.0   by Tom Irvine';
            app.TabGroup = uitabgroup(app.dBCalculationsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.aTab = uitab(app.TabGroup);
            app.aTab.Title = 'a';
            app.CalculateButton = uibutton(app.aTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DimensionListBox_2Label = uilabel(app.aTab);
            app.DimensionListBox_2Label.Text = 'Dimension';
            app.DimensionListBox_2 = uilistbox(app.aTab);
            app.DimensionListBox_2.Items = {'SRS (G)', 'PSD (G^2/Hz)'};
            app.DimensionListBox_2.ValueChangedFcn = createCallbackFcn(app, @DimensionListBox_2ValueChanged, true);
            app.DimensionListBox_2.Value = 'SRS (G)';
            app.AnalysisListBox_2Label = uilabel(app.aTab);
            app.AnalysisListBox_2Label.Text = 'Analysis';
            app.AnalysisListBox_2 = uilistbox(app.aTab);
            app.AnalysisListBox_2.Items = {'Calculate slope between coordinates', 'Find new coordinate given slope and one coordinate', 'Find new coordinate given two coordinates'};
            app.AnalysisListBox_2.ValueChangedFcn = createCallbackFcn(app, @AnalysisListBox_2ValueChanged, true);
            app.AnalysisListBox_2.Value = 'Calculate slope between coordinates';
            app.SlopedBoctfromlowertohigherfrequencyLabel = uilabel(app.aTab);
            app.SlopedBoctfromlowertohigherfrequencyLabel.Text = {'Slope (dB/oct) from lower '; 'to higher frequency'};
            app.SlopedBoctfromlowertohigherfrequencyEditField = uieditfield(app.aTab, 'text');
            app.SlopedBoctfromlowertohigherfrequencyEditField.ValueChangingFcn = createCallbackFcn(app, @SlopedBoctfromlowertohigherfrequencyEditFieldValueChanging, true);
            app.NewCoordinateFrequencyHzEditFieldLabel = uilabel(app.aTab);
            app.NewCoordinateFrequencyHzEditFieldLabel.Text = {'New Coordinate '; 'Frequency (Hz)'};
            app.NewCoordinateFrequencyHzEditField = uieditfield(app.aTab, 'text');
            app.NewCoordinateFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NewCoordinateFrequencyHzEditFieldValueChanging, true);
            app.FrequencyHzEditFieldLabel = uilabel(app.aTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.aTab, 'text');
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanging, true);
            app.SRSGEditFieldLabel = uilabel(app.aTab);
            app.SRSGEditFieldLabel.Text = 'SRS (G)';
            app.SRSGEditField = uieditfield(app.aTab, 'text');
            app.SRSGEditField.ValueChangingFcn = createCallbackFcn(app, @SRSGEditFieldValueChanging, true);
            app.EditField_3 = uieditfield(app.aTab, 'text');
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_4 = uieditfield(app.aTab, 'text');
            app.EditField_4.ValueChangingFcn = createCallbackFcn(app, @EditField_4ValueChanging, true);
            app.Label = uilabel(app.aTab);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.aTab);
            app.Label_2.Text = '2';
            app.bTab = uitab(app.TabGroup);
            app.bTab.Title = 'b';
            app.CalculateButton_2 = uibutton(app.bTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ResultEditFieldLabel = uilabel(app.bTab);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.bTab, 'text');
            app.DimensionListBoxLabel = uilabel(app.bTab);
            app.DimensionListBoxLabel.Text = 'Dimension';
            app.DimensionListBox = uilistbox(app.bTab);
            app.DimensionListBox.Items = {'SRS (G)', 'Overall (GRMS)', 'PSD (G^2/Hz)'};
            app.DimensionListBox.ValueChangedFcn = createCallbackFcn(app, @DimensionListBoxValueChanged, true);
            app.DimensionListBox.Value = 'SRS (G)';
            app.AnalysisListBoxLabel = uilabel(app.bTab);
            app.AnalysisListBoxLabel.Text = 'Analysis';
            app.AnalysisListBox = uilistbox(app.bTab);
            app.AnalysisListBox.Items = {'Calculate dB difference between two levels', 'Find new level given dB difference and one level'};
            app.AnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @AnalysisListBoxValueChanged, true);
            app.AnalysisListBox.Value = 'Calculate dB difference between two levels';
            app.Point1SRSGEditFieldLabel = uilabel(app.bTab);
            app.Point1SRSGEditFieldLabel.Text = 'Point 1 SRS (G)';
            app.Point1SRSGEditField = uieditfield(app.bTab, 'text');
            app.Point1SRSGEditField.ValueChangingFcn = createCallbackFcn(app, @Point1SRSGEditFieldValueChanging, true);
            app.Point2SRSGEditFieldLabel = uilabel(app.bTab);
            app.Point2SRSGEditFieldLabel.Text = 'Point 2 SRS (G)';
            app.Point2SRSGEditField = uieditfield(app.bTab, 'text');
            app.Point2SRSGEditField.ValueChangingFcn = createCallbackFcn(app, @Point2SRSGEditFieldValueChanging, true);
            app.cTab = uitab(app.TabGroup);
            app.cTab.Title = 'c';
            app.NumberofLevelsListBoxLabel = uilabel(app.cTab);
            app.NumberofLevelsListBoxLabel.Text = {'Number '; 'of Levels'};
            app.NumberofLevelsListBox = uilistbox(app.cTab);
            app.NumberofLevelsListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofLevelsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofLevelsListBoxValueChanged, true);
            app.NumberofLevelsListBox.Value = '2';
            app.UITable = uitable(app.cTab);
            app.UITable.ColumnName = {'dB'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.CalculateButton_3 = uibutton(app.cTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ResultsTextAreaLabel = uilabel(app.cTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.cTab);
            app.dTab = uitab(app.TabGroup);
            app.dTab.Title = 'd';
            app.dBMarginEditFieldLabel = uilabel(app.dTab);
            app.dBMarginEditFieldLabel.Text = 'dB Margin';
            app.dBMarginEditField = uieditfield(app.dTab, 'text');
            app.dBMarginEditField.ValueChangingFcn = createCallbackFcn(app, @dBMarginEditFieldValueChanging, true);
            app.CalculateButton_4 = uibutton(app.dTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.FunctionTypeListBoxLabel = uilabel(app.dTab);
            app.FunctionTypeListBoxLabel.Text = 'Function Type';
            app.FunctionTypeListBox = uilistbox(app.dTab);
            app.FunctionTypeListBox.Items = {'PSD', 'SRS', 'SPL'};
            app.FunctionTypeListBox.ValueChangedFcn = createCallbackFcn(app, @FunctionTypeListBoxValueChanged, true);
            app.FunctionTypeListBox.Value = 'PSD';
            app.listbox_method = uilistbox(app.dTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.dTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.dTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.dTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.dTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ExportNameEditFieldLabel = uilabel(app.dTab);
            app.ExportNameEditFieldLabel.Text = 'Export Name';
            app.ExportNameEditField = uieditfield(app.dTab, 'text');
            app.OutputPlotTitleEditFieldLabel = uilabel(app.dTab);
            app.OutputPlotTitleEditFieldLabel.Text = 'Output Plot Title';
            app.OutputPlotTitleEditField = uieditfield(app.dTab, 'text');
            app.DomainLimitsLabel = uilabel(app.dTab);
            app.DomainLimitsLabel.Text = 'X-axis Domain Limits';
            app.DomainLimitsListBox_X = uilistbox(app.dTab);
            app.DomainLimitsListBox_X.Items = {'automatic', 'manual'};
            app.DomainLimitsListBox_X.ValueChangedFcn = createCallbackFcn(app, @DomainLimitsListBox_XValueChanged, true);
            app.DomainLimitsListBox_X.Value = 'automatic';
            app.MinEditFieldLabel = uilabel(app.dTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField_X = uieditfield(app.dTab, 'text');
            app.MaxEditFieldLabel = uilabel(app.dTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField_X = uieditfield(app.dTab, 'text');
            app.eTab = uitab(app.TabGroup);
            app.eTab.Title = 'e';
            app.CalculateButton_5 = uibutton(app.eTab, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.FunctionsListBoxLabel = uilabel(app.eTab);
            app.FunctionsListBoxLabel.Text = 'Functions';
            app.FunctionsListBox = uilistbox(app.eTab);
            app.FunctionsListBox.Items = {'SRS (G)', 'PSD (G^2/Hz)', 'FDS (G^4)', 'FDS (G^8)'};
            app.FunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @FunctionsListBoxValueChanged, true);
            app.FunctionsListBox.Value = 'SRS (G)';
            app.ListboxMethod = uilistbox(app.eTab);
            app.ListboxMethod.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod.ValueChangedFcn = createCallbackFcn(app, @ListboxMethodValueChanged, true);
            app.ListboxMethod.Tag = 'listbox_method';
            app.ListboxMethod.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.eTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.ListboxFormat = uilistbox(app.eTab);
            app.ListboxFormat.Items = {'Common array:  freq(Hz) & amplitude & amplitude', 'Two separate arrays, each:  freq(Hz) & amplitude'};
            app.ListboxFormat.ValueChangedFcn = createCallbackFcn(app, @ListboxFormatValueChanged, true);
            app.ListboxFormat.Tag = 'listbox_method';
            app.ListboxFormat.Value = 'Common array:  freq(Hz) & amplitude & amplitude';
            app.text1_3 = uilabel(app.eTab);
            app.text1_3.Tag = 'text1';
            app.text1_3.Text = 'Select Data Input Format';
            app.UpdateListButton_2 = uibutton(app.eTab, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.eTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_2 = uilistbox(app.eTab);
            app.SelectInputArrayListBox_2.Items = {};
            app.SelectInputArrayListBox_2.Value = {};
            app.SelectResponseInputArrayListBoxLabel = uilabel(app.eTab);
            app.SelectResponseInputArrayListBoxLabel.Text = 'Select Response Input Array';
            app.SelectResponseInputArrayListBox = uilistbox(app.eTab);
            app.SelectResponseInputArrayListBox.Items = {};
            app.SelectResponseInputArrayListBox.Value = {};
            app.PlotTitleEditFieldLabel = uilabel(app.eTab);
            app.PlotTitleEditFieldLabel.Text = 'PlotTitle';
            app.PlotTitleEditField = uieditfield(app.eTab, 'text');
            app.OutputArrayNameEditFieldLabel = uilabel(app.eTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.eTab, 'text');
            app.OutputArrayNameEditField.Value = 'dB_difference';
            app.PlotdBLimitsLabel = uilabel(app.eTab);
            app.PlotdBLimitsLabel.Text = 'Plot dB Limits';
            app.PlotdBLimitsListBox = uilistbox(app.eTab);
            app.PlotdBLimitsListBox.Items = {'Automatic', 'Manual'};
            app.PlotdBLimitsListBox.ValueChangedFcn = createCallbackFcn(app, @PlotdBLimitsListBoxValueChanged, true);
            app.PlotdBLimitsListBox.Value = 'Automatic';
            app.MaxEditFieldLabel_2 = uilabel(app.eTab);
            app.MaxEditFieldLabel_2.Text = 'Max ';
            app.MaxEditField = uieditfield(app.eTab, 'text');
            app.MinEditFieldLabel_2 = uilabel(app.eTab);
            app.MinEditFieldLabel_2.Text = 'Min ';
            app.MinEditField = uieditfield(app.eTab, 'text');
            app.fTab = uitab(app.TabGroup);
            app.fTab.Title = 'f';
            app.listbox_method_kd = uilistbox(app.fTab);
            app.listbox_method_kd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_kd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_kdValueChanged, true);
            app.listbox_method_kd.Tag = 'listbox_method';
            app.listbox_method_kd.Value = 'Array Preloaded in Matlab';
            app.text1_4 = uilabel(app.fTab);
            app.text1_4.Tag = 'text1';
            app.text1_4.Text = 'Select Data Input Method';
            app.UpdateListButton_kd = uibutton(app.fTab, 'push');
            app.UpdateListButton_kd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_kdPushed, true);
            app.UpdateListButton_kd.Text = 'Update List';
            app.SelectInputArrayListBox_3Label = uilabel(app.fTab);
            app.SelectInputArrayListBox_3Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_kd = uilistbox(app.fTab);
            app.SelectInputArrayListBox_kd.Items = {'Item 1'};
            app.AmplitudeTypeListBoxLabel = uilabel(app.fTab);
            app.AmplitudeTypeListBoxLabel.Text = 'Amplitude Type';
            app.AmplitudeTypeListBox = uilistbox(app.fTab);
            app.AmplitudeTypeListBox.Items = {'dB', 'G^2/Hz', 'G'};
            app.AmplitudeTypeListBox.Value = 'G';
            app.YAxisLabelEditFieldLabel = uilabel(app.fTab);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.fTab, 'text');
            app.StartEditFieldLabel = uilabel(app.fTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.fTab, 'text');
            app.StartEditField.Value = '20';
            app.EndEditFieldLabel = uilabel(app.fTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.fTab, 'text');
            app.EndEditField.Value = '2000';
            app.PlotFrequenciesLabel = uilabel(app.fTab);
            app.PlotFrequenciesLabel.Text = 'Plot Frequencies';
            app.NumberofFrequenciesListBoxLabel = uilabel(app.fTab);
            app.NumberofFrequenciesListBoxLabel.Text = {'Number of '; 'Frequencies '};
            app.NumberofFrequenciesListBox = uilistbox(app.fTab);
            app.NumberofFrequenciesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofFrequenciesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofFrequenciesListBoxValueChanged, true);
            app.NumberofFrequenciesListBox.Value = '1';
            app.UITable_kd = uitable(app.fTab);
            app.UITable_kd.ColumnName = {'Start Freq (Hz)'; 'End Freq (Hz)'; 'dB'};
            app.UITable_kd.RowName = {};
            app.UITable_kd.ColumnEditable = true;
            app.CalculateButton_kd = uibutton(app.fTab, 'push');
            app.CalculateButton_kd.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_kdPushed, true);
            app.CalculateButton_kd.Text = 'Calculate';
            app.PlotTitleLabel = uilabel(app.fTab);
            app.PlotTitleLabel.Text = 'Plot Title';
            app.PlotTitleEditField_2 = uieditfield(app.fTab, 'text');
            app.ExportNameEditField_2Label = uilabel(app.fTab);
            app.ExportNameEditField_2Label.Text = 'Export Name';
            app.ExportNameEditField_2 = uieditfield(app.fTab, 'text');
            app.SelectAnalysisListBoxLabel = uilabel(app.dBCalculationsUIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.dBCalculationsUIFigure);
            app.SelectAnalysisListBox.Items = {'Interpolation or Extrapolation', 'Difference between Two Points', 'Adding Decibles from Multiple Sources', 'Add dB Margin', 'Difference between Two Functions', 'dB Knockdown by Frequency'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Interpolation or Extrapolation';
            app.PerformAnalysisButton = uibutton(app.dBCalculationsUIFigure, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.ResultsTextArea_2Label = uilabel(app.dBCalculationsUIFigure);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.ResultsTextArea_2 = uitextarea(app.dBCalculationsUIFigure);
            % Show the figure after all components are created
            app.dBCalculationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dboct_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.dBCalculationsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.dBCalculationsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dBCalculationsUIFigure)
        end
    end
end