classdef earthquake_synth_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        earthquakesynthesisUIFigure    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EnterSRSTimeHistoryTab         matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SRSTab                         matlab.ui.container.Tab
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        UpdateSRSListButton            matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        SelectSRSInputArrayListBox     matlab.ui.control.ListBox
        SelectSRSInputArrayListBoxLabel  matlab.ui.control.Label
        TimeHistoryTab                 matlab.ui.container.Tab
        TheinputtimehistorymusthavetwocolumnstimesecaccelGLabel  matlab.ui.control.Label
        text1_2                        matlab.ui.control.Label
        listbox_method_th              matlab.ui.control.ListBox
        SelectTimeHistoryInputArrayListBox  matlab.ui.control.ListBox
        SelectTimeHistoryInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateTimeHistoryListButton    matlab.ui.control.Button
        CalculateTab                   matlab.ui.container.Tab
        RandomNumberSeedListBox        matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel   matlab.ui.control.Label
        DisplacementLimitinEditField   matlab.ui.control.EditField
        DisplacementLimitinEditFieldLabel  matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        SRSErrorLimitdBEditField       matlab.ui.control.EditField
        SRSErrorLimitdBEditFieldLabel  matlab.ui.control.Label
        NumberofSecondaryWaveletsEditField  matlab.ui.control.EditField
        NumberofSecondaryWaveletsEditFieldLabel  matlab.ui.control.Label
        NumberofPrimaryWaveletsEditField  matlab.ui.control.EditField
        NumberofPrimaryWaveletsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField    matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        DurationsecEditField           matlab.ui.control.EditField
        DurationsecEditFieldLabel      matlab.ui.control.Label
        SREditField                    matlab.ui.control.EditField
        SampleRatesuggest10xmaxSRSfreqEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        TimeHistoryEditField           matlab.ui.control.EditField
        TimeHistoryEditFieldLabel      matlab.ui.control.Label
        SRSEditField                   matlab.ui.control.EditField
        SRSEditFieldLabel              matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FunctionListBox                matlab.ui.control.ListBox
        FunctionListBoxLabel           matlab.ui.control.Label
        earthquake_synth_aver21byTomIrvineLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        kth_flag % Description
        THM
        THS
        iu
        accel_array
        vel_array
        dispx_array
        srs
        wtable
        decomposition
        intermediate
    end
    
    methods (Access = private)

      function ChangeUnits(app)

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.DisplacementLimitinEditFieldLabel.Text='Displacement Limit (in)';
            else
                app.DisplacementLimitinEditFieldLabel.Text='Displacement Limit (mm)';
            end    

      end

      function update_srs(app)
            cell_array=update_shock_response_spectrum();
            app.SelectSRSInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
       end 

       function update_th(app)

            [cell_array]=update_two_columns();

            app.kth_flag=0;  

            app.SelectTimeHistoryInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method_th.Value=app.listbox_method_th.Items{1};
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update_th(app);
            update_srs(app);
            ChangeUnits(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateTimeHistoryListButton
        function UpdateTimeHistoryListButtonPushed(app, event)
            update_th(app);
        end

        % Button pushed function: UpdateSRSListButton
        function UpdateSRSListButtonPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_th
        function listbox_method_thValueChanged(app, event)

            Items = char(app.listbox_method_th.Items);
            im=app.listbox_method_th.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_th(app);

               try
                    app.SelectTimeHistoryInputArrayListBox.Value=name;
               catch
               end
            end    
            
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THS = fscanf(fid,'%g %g',[2 inf]);
                   app.THS=app.THS';
                   size(app.THS)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THS);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               app.SelectSRSInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
            
        end

        % Button down function: CalculateTab
        function CalculateTabButtonDown(app, event)
            try
                app.SRSEditField.Value=app.SelectSRSInputArrayListBox.Value;
            catch
            end
            try
                app.TimeHistoryEditField.Value=app.SelectTimeHistoryInputArrayListBox.Value;
            catch
            end
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ChangeUnits(app);
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            tic

            fig_num=1;

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
                FS1=strtrim(app.SRSEditField.Value);
                app.THS=evalin('base',FS1);
            catch
                warndlg('SRS array does not exist','Warning');
                return;
            end
            
            fr=app.THS(:,1);
            r=app.THS(:,2);
            
            error_limit=str2num(app.SRSErrorLimitdBEditField.Value);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            uewf=1.25;
            
            Q=str2num(app.AmplificationFactorQEditField.Value);
            iter=str2num(app.NumberofIterationsEditField.Value);
            
            thu=strtrim(app.TimeHistoryEditField.Value);
            
            if isempty(thu)
                warndlg('Time history does not exist');
                return;
            else
                app.THM=evalin('base',thu);    
            end
            
            app.THM(:,1)=app.THM(:,1)-app.THM(1,1);
            
            reference_accel=app.THM;
            
            t=app.THM(:,1);
            accel=app.THM(:,2);
            
            num=length(t);
            duration=t(num)-t(1);
            dt=duration/(num-1);
            sr=1/dt;
            
            du=str2num(app.DurationsecEditField.Value);
            dur=du;
            
            if isempty(du)
                dur=duration;
                out1=sprintf('%8.4g',duration);
                app.DurationsecEditField.Value=out1;    
            end
                
            au=app.SREditField.Value;
            ssr=str2num(au);   
            
            if isempty(au)
                ssr=sr;
                out1=sprintf('%8.4g',sr);
                app.SREditField.Value=out1;
            end
            
            %%
            
            maxf=max(fr);
            
            if(ssr<10*maxf)
                warndlg('Sample rate is too low ');
            end
            
            if(ssr>60*maxf)
                warndlg('Sample rate is higher than needed ');
            end
            
            %%
               
            ffmax=sr/10;
            
            nfr=str2num(app.NumberofPrimaryWaveletsEditField.Value);
            nsec=str2num(app.NumberofSecondaryWaveletsEditField.Value);
            
            start_time=t(1);
            first=t(1);
            
            nt=2000;
            
            [acceleration,~,~,wavelet_table,~,~,~,~]=...
              vibrationdata_wavelet_decomposition_engine_q(t,accel,dt,first,...
                                                              ffmax,nt,nfr,start_time);
                                                          
            wavelet_table_add=wavelet_table;

            ta=min([t(1) acceleration(1,1)]);
            tb=max([max(t) max(acceleration(:,1))]);
            
            figure(fig_num);
            fig_num=fig_num+1;
            subplot(2,1,1);
            plot(t,accel);
            title('Reference');
            ylabel('Accel (G)');
            grid on;
            xlim([ta tb]);
            
            subplot(2,1,2);
            plot(acceleration(:,1),acceleration(:,2));
            title('Wavelet Decomposition');
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            grid on;
            xlim([ta tb]);
            
            app.decomposition=acceleration;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sz=size(wavelet_table);
            
            last_wavelet=sz(1);
            
            f=zeros(last_wavelet,1);
            amp=zeros(last_wavelet,1);
            NHS=zeros(last_wavelet,1);
            td=zeros(last_wavelet,1);
            %
            %
            for i=1:last_wavelet
                f(i)=wavelet_table_add(i,2);
                amp(i)=wavelet_table_add(i,3);
                NHS(i)=wavelet_table_add(i,4); 
                td(i)=wavelet_table_add(i,5);  
            end
            
            dur_ref=0;
            
            for i=1:last_wavelet
                d=td(i)+(NHS(i)/2*f(i));
                if(d>dur_ref)
                    dur_ref=d;
                end
            end
            %
            
            if(dur_ref<dur)
                dur=dur_ref;
                disp(' ');
                disp(' Duration increased ');
            end
            
            %
            dt=1./ssr;
            %
            % nt=ceil(dur/dt);
            %
            damp=1/(2*Q);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % clear t;
            
            % t=linspace(0,nt*dt,nt+1); 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            ioct=3;
            
            dlimit=str2num(app.DisplacementLimitinEditField.Value);
            
            if(app.iu==1)
                dlimit=dlimit/386;
            else
                dlimit=dlimit/(9.81*1000);
            end
            
            [fn,srs_spec]=SRS_specification_interpolation(fr,r,ioct);
            
            [a1,a2,b1,b2,b3,~,~,~,~,~]=srs_coefficients(fn,damp,dt);  
            
            %%%
            
            dur=str2num(app.DurationsecEditField.Value);
                                              
            [amp,~,wavelet_low,wavelet_up,alpha,beta,t]=...
                quake_srs_synth_phase_1(f,dt,damp,amp,NHS,td,last_wavelet,iter,...
                                          fn,srs_spec,dlimit,error_limit,app.iu,uewf,dur);
            
            % f, NHS, & td remain unchanged
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            [raccel]=generate_time_history_wavelet_table_q(f,amp,NHS,td,t,...
                                                    wavelet_low,wavelet_up,alpha,beta);
            
            ta=min([reference_accel(1,1) acceleration(1,1) t(1,1)]);
            tb=max([max(reference_accel(:,1)) max(acceleration(:,1)) max(t(:,1))]);

            ymax=max([ max(reference_accel(:,2))  max(acceleration(:,2))  max(raccel)]);
            ymin=min([ min(reference_accel(:,2))  min(acceleration(:,2))  min(raccel)]);

            yabs=max([ymax abs(ymin)]);
            [~,yTT,ytt,iflag]=ytick_linear_double_sided(yabs);
            
            figure(fig_num);
            fig_num=fig_num+1;
            
            subplot(3,1,1);
            plot(reference_accel(:,1),reference_accel(:,2));
            title('Reference');
            ylabel('Accel (G)');
            set(gca,'XTickLabel',[]);
            grid on;
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end    
            set(gca, 'xminorgrid', 'off', 'yminorgrid', 'off')
             
            subplot(3,1,2);
            plot(acceleration(:,1),acceleration(:,2));
            title('Wavelet Decomposition');
            ylabel('Accel (G)');
            set(gca,'XTickLabel',[]);
            grid on;
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end 
            set(gca, 'xminorgrid', 'off', 'yminorgrid', 'off')

            subplot(3,1,3);
            plot(t,raccel);
            title('Intermediate');
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            grid on
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end  
            set(gca, 'xminorgrid', 'off', 'yminorgrid', 'off')
            
            t=fix_size(t);
            raccel=fix_size(raccel);
            
            app.intermediate=[t raccel];

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [raccel,ramp,last_wavelet,f,amp,NHS,td,wavelet_low,wavelet_up,alpha,beta]=...
                   quake_srs_synth_phase_3(f,dt,amp,NHS,td,t,last_wavelet,...
                            srs_spec,dlimit,fn,raccel,a1,a2,b1,b2,b3,nsec,...
                                        error_limit,wavelet_low,wavelet_up,alpha,beta);
                                    
            [dispx,vel,acc]=wavelet_integrate_q(f,ramp,NHS,td,dt,dur,t,...
                                                    wavelet_low,wavelet_up,alpha,beta);
            
            raccel=acc;                                    
                                    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            num=length(fn);     
            
            rxmax=zeros(num,1);
            rxmin=zeros(num,1);
                                           
            for j=1:num
                   
                    forward=[ b1(j),  b2(j),  b3(j) ];    
                    back   =[     1, -a1(j), -a2(j) ];    
            %    
                    resp=filter(forward,back,raccel);
            %
                    rxmax(j)=max(resp);
                    rxmin(j)=abs(min(resp));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            f=fix_size(f);
            amp=fix_size(amp);
            NHS=fix_size(NHS);
            td=fix_size(td);
            
            app.wtable=[f amp NHS td];
            
            if(app.iu==1)
                vel=vel*386;
                dispx=dispx*386;
                vy='Vel (in/sec)';
                dy='Disp (in)';
            else
                vel=vel*9.81*100;
                dispx=dispx*9.81*1000;   
                vy='Vel (cm/sec)';
                dy='Disp (mm)';    
            end
            
            t=fix_size(t);
            dispx=fix_size(dispx);
            vel=fix_size(vel);
            acc=fix_size(acc);
            
            app.accel_array=[t acc];
            
            app.dispx_array=[t dispx];
            app.vel_array=[t vel];
          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ta=min([reference_accel(1,1) acceleration(1,1) app.accel_array(1,1)]);
            tb=max([max(reference_accel(:,1)) max(acceleration(:,1)) max(app.accel_array(:,1))]);
            
            figure(fig_num);
            fig_num=fig_num+1;

            ymax=max([ max(reference_accel(:,2))  max(acceleration(:,2))  max(app.accel_array(:,2))]);
            ymin=min([ min(reference_accel(:,2))  min(acceleration(:,2))  min(app.accel_array(:,2))]);

            yabs=max([ymax abs(ymin)]);
            [~,yTT,ytt,iflag]=ytick_linear_double_sided(yabs);


            subplot(3,1,1);
            plot(reference_accel(:,1),reference_accel(:,2));
            title('Reference');
            ylabel('Accel (G)');
            set(gca,'XTickLabel',[]);
            grid on;
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end
             
            subplot(3,1,2);
            plot(acceleration(:,1),acceleration(:,2));
            title('Wavelet Decomposition');
            ylabel('Accel (G)');
            set(gca,'XTickLabel',[]);
            grid on;
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end
            
            subplot(3,1,3);
            plot(app.accel_array(:,1),app.accel_array(:,2));
            title('Modifed');
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            grid on;
            xlim([ta tb]);
            if(iflag==1)
                set(gca,'ytick',ytt);
                set(gca,'YTickLabel',yTT);
                ylim([min(ytt),max(ytt)]);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(fig_num);
            fig_num=fig_num+1;
            subplot(3,1,1);
            plot(app.accel_array(:,1),app.accel_array(:,2));
            title('Modified Set');
            ylabel('Accel (G)');
            set(gca,'XTickLabel',[]);
            grid on;
            
            subplot(3,1,2);
            plot(t,vel);
            ylabel(vy);
            set(gca,'XTickLabel',[]);
            grid on;
            
            subplot(3,1,3);
            plot(t,dispx);
            ylabel(dy);
            xlabel('Time(sec)');
            grid on;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(fig_num);
            fig_num=fig_num+1;
            t1=r/sqrt(2);
            t2=r*sqrt(2);
            plot(fn,rxmax,fn,rxmin,fr,t2,fr,r,fr,t1);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            legend('Synth Pos','Synth Neg','+3 dB','Spec','-3 dB','location','northwest');
            grid on;
            out1=sprintf('Shock Response Spectrum Q=%g',Q);
            title(out1);
            xlabel('Natural Frequency (Hz)');
            ylabel('Accel (G)');
            
            fn=fix_size(fn);
            rxmax=fix_size(rxmax);
            rxmin=fix_size(rxmin);
            
            [aay]=get(gca,'ylim');
            
            ylim([aay(1) 10*aay(2)]);
            
            
            app.srs=[fn rxmax rxmin];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            t=toc;
            
            minutes=round(t/60);
            
            fprintf('\n Elapsed Time = %g min \n',minutes);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = strtrim(app.FunctionListBox.Items);
            im = strtrim(app.FunctionListBox.Value);
            n = find(strcmp(Items, im));

            output_name=app.OutputArrayNameEditField.Value;
                     
            if(n==1)
                data=app.accel_array;
            end
            if(n==2)
                data=app.vel_array;    
            end
            if(n==3)
                data=app.dispx_array; 
            end
            if(n==4)
                data=app.srs; 
            end
            if(n==5)
                data=app.wtable; 
            end
            if(n==6)
                data=app.decomposition; 
            end
            if(n==7)
                data=app.intermediate; 
            end
            
            assignin('base', output_name, data);
            
            msgbox('Array saved');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.earthquakesynthesisUIFigure = uifigure('Visible', 'off');
            app.earthquakesynthesisUIFigure.Name = 'earthquake synthesis';
            app.ReturnButton = uibutton(app.earthquakesynthesisUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.earthquakesynthesisUIFigure);
            app.Label.Text = 'This script synthesizes a time history using wavelets to meet an SRS, where the resulting time history is modeled using measured data.';
            app.earthquake_synth_aver21byTomIrvineLabel = uilabel(app.earthquakesynthesisUIFigure);
            app.earthquake_synth_aver21byTomIrvineLabel.Text = 'earthquake_synth_a   ver 2.1   by Tom Irvine';
            app.TabGroup = uitabgroup(app.earthquakesynthesisUIFigure);
            app.EnterSRSTimeHistoryTab = uitab(app.TabGroup);
            app.EnterSRSTimeHistoryTab.Title = 'Enter SRS & Time History';
            app.TabGroup2 = uitabgroup(app.EnterSRSTimeHistoryTab);
            app.SRSTab = uitab(app.TabGroup2);
            app.SRSTab.Title = 'SRS';
            app.SelectSRSInputArrayListBoxLabel = uilabel(app.SRSTab);
            app.SelectSRSInputArrayListBoxLabel.Text = 'Select SRS Input Array';
            app.SelectSRSInputArrayListBox = uilistbox(app.SRSTab);
            app.SelectSRSInputArrayListBox.Items = {'Item 1'};
            app.listbox_method = uilistbox(app.SRSTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.SRSTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select SRS Input Method';
            app.UpdateSRSListButton = uibutton(app.SRSTab, 'push');
            app.UpdateSRSListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSRSListButtonPushed, true);
            app.UpdateSRSListButton.Text = 'Update SRS List';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.SRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: '; 'Natural Frequency (Hz) & SRS (G)'};
            app.TimeHistoryTab = uitab(app.TabGroup2);
            app.TimeHistoryTab.Title = 'Time History';
            app.UpdateTimeHistoryListButton = uibutton(app.TimeHistoryTab, 'push');
            app.UpdateTimeHistoryListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTimeHistoryListButtonPushed, true);
            app.UpdateTimeHistoryListButton.Text = 'Update Time History List';
            app.SelectTimeHistoryInputArrayListBoxLabel = uilabel(app.TimeHistoryTab);
            app.SelectTimeHistoryInputArrayListBoxLabel.Text = 'Select Time History Input Array';
            app.SelectTimeHistoryInputArrayListBox = uilistbox(app.TimeHistoryTab);
            app.SelectTimeHistoryInputArrayListBox.Items = {'Item 1'};
            app.listbox_method_th = uilistbox(app.TimeHistoryTab);
            app.listbox_method_th.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_th.ValueChangedFcn = createCallbackFcn(app, @listbox_method_thValueChanged, true);
            app.listbox_method_th.Tag = 'listbox_method';
            app.listbox_method_th.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.TimeHistoryTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = {'Select Time History '; 'Input Method'};
            app.TheinputtimehistorymusthavetwocolumnstimesecaccelGLabel = uilabel(app.TimeHistoryTab);
            app.TheinputtimehistorymusthavetwocolumnstimesecaccelGLabel.Text = {'The input time history must have two columns:  '; 'time (sec) & accel (G)'};
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateTab.ButtonDownFcn = createCallbackFcn(app, @CalculateTabButtonDown, true);
            app.SRSEditFieldLabel = uilabel(app.CalculateTab);
            app.SRSEditFieldLabel.Text = 'SRS';
            app.SRSEditField = uieditfield(app.CalculateTab, 'text');
            app.SRSEditField.Editable = 'off';
            app.TimeHistoryEditFieldLabel = uilabel(app.CalculateTab);
            app.TimeHistoryEditFieldLabel.Text = 'Time History';
            app.TimeHistoryEditField = uieditfield(app.CalculateTab, 'text');
            app.TimeHistoryEditField.Editable = 'off';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.CalculateTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.CalculateTab, 'text');
            app.AmplificationFactorQEditField.Value = '10';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SampleRatesuggest10xmaxSRSfreqEditFieldLabel = uilabel(app.CalculateTab);
            app.SampleRatesuggest10xmaxSRSfreqEditFieldLabel.Text = {'Sample Rate '; '( suggest >= 10 x max SRS freq )'};
            app.SREditField = uieditfield(app.CalculateTab, 'text');
            app.DurationsecEditFieldLabel = uilabel(app.CalculateTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofIterationsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofIterationsEditField.Value = '60000';
            app.NumberofPrimaryWaveletsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofPrimaryWaveletsEditFieldLabel.Text = 'Number of Primary Wavelets';
            app.NumberofPrimaryWaveletsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofPrimaryWaveletsEditField.Value = '250';
            app.NumberofSecondaryWaveletsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofSecondaryWaveletsEditFieldLabel.Text = 'Number of Secondary Wavelets';
            app.NumberofSecondaryWaveletsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofSecondaryWaveletsEditField.Value = '250';
            app.SRSErrorLimitdBEditFieldLabel = uilabel(app.CalculateTab);
            app.SRSErrorLimitdBEditFieldLabel.Text = 'SRS Error Limit (dB)';
            app.SRSErrorLimitdBEditField = uieditfield(app.CalculateTab, 'text');
            app.SRSErrorLimitdBEditField.Value = '1.5';
            app.UnitsListBoxLabel = uilabel(app.CalculateTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.CalculateTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm ', ''};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.DisplacementLimitinEditFieldLabel = uilabel(app.CalculateTab);
            app.DisplacementLimitinEditFieldLabel.Text = 'Displacement Limit (in)';
            app.DisplacementLimitinEditField = uieditfield(app.CalculateTab, 'text');
            app.RandomNumberSeedListBoxLabel = uilabel(app.CalculateTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.CalculateTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.FunctionListBoxLabel = uilabel(app.ExportTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTab);
            app.FunctionListBox.Items = {'Acceleration', 'Velocity    ', 'Displacement', 'SRS', 'Wavelet Table', 'Decomposition', 'Intermediate'};
            app.FunctionListBox.Value = 'Acceleration';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            % Show the figure after all components are created
            app.earthquakesynthesisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = earthquake_synth_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.earthquakesynthesisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.earthquakesynthesisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.earthquakesynthesisUIFigure)
        end
    end
end