classdef envelope_fds_sine_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MsgEditField                   matlab.ui.control.EditField
        MsgEditFieldLabel              matlab.ui.control.Label
        LoadModelButton                matlab.ui.control.Button
        SaveModelButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        EnterFDSDataTab                matlab.ui.container.Tab
        NumberofFDSArraysListBox       matlab.ui.control.ListBox
        NumberofFDSArraysListBoxLabel  matlab.ui.control.Label
        ExtractQbfromArrayNameButton   matlab.ui.control.Button
        UITable_FDS_PSD                matlab.ui.control.Table
        DoubleclickonArrayNametoaccesspulldownmenuLabel  matlab.ui.control.Label
        InputFDSTypeListBox            matlab.ui.control.ListBox
        InputFDSTypeListBoxLabel       matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SelectSineTonesTab             matlab.ui.container.Tab
        NumberofSinesListBox           matlab.ui.control.ListBox
        NumberofSinesListBoxLabel      matlab.ui.control.Label
        UITableSine                    matlab.ui.control.Table
        CalculateEnvelopeTab           matlab.ui.container.Tab
        MaxSlopedBoctEditField         matlab.ui.control.EditField
        MaxSlopedBoctEditField_2Label  matlab.ui.control.Label
        OptimizedArrayOuputNamePrefixEditField  matlab.ui.control.EditField
        OptimizedArrayOuputNamePrefixEditFieldLabel  matlab.ui.control.Label
        RandomNumberSeedListBox        matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel   matlab.ui.control.Label
        NumberofPSDCoordinatesListBox  matlab.ui.control.ListBox
        NumberofPSDCoordinatesListBoxLabel  matlab.ui.control.Label
        OutputPSDUnitListBox           matlab.ui.control.ListBox
        OutputPSDUnitListBoxLabel      matlab.ui.control.Label
        NumberofTrialsEditField        matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel   matlab.ui.control.Label
        PSDDurationsecEditField        matlab.ui.control.EditField
        PSDDurationsecEditFieldLabel   matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ResultsExportsTab              matlab.ui.container.Tab
        WorkspaceArraysTextArea        matlab.ui.control.TextArea
        WorkspaceArraysTextAreaLabel   matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        AData
        bex
        FDS_name
        fmax
        fmin
        goal
        iflag
        ip
        iu
        kflag
        msz
        name
        nmetric
        npb
        ntrials
        num_tones 
        power_spectral_density
        Q
        slopec
        T_out
        THM
    end
    
    methods (Access = private)

        function cleartext(app)            
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            % app.ExportOutputsButton.Visible='off';
            % app.OutputArrayNamesTextArea.Value=' ';
        end    

        function ChangeRows_FDS_PSD(app)

            disp('ChangeRows');

            ns=app.NumberofFDSArraysListBox.Value;
            num_arrays=str2num(ns); 

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || ...
                    contains(vars(i).name,'vname_list') || ...
                    contains(vars(i).name,'amp_cycles') || ...
                    contains(vars(i).name,'range_cycles') || ...
                    contains(vars(i).name,'amp_mean_cycles') || ...
                    contains(vars(i).name,'range_cycles_max_min'))
                    cell_array{i} ='999';
                else  

                    try   
                        app.THM=evalin('base',vars(i).name);
                        sz=size(app.THM);

                        if(sz(2)==2 && contains(vars(i).name,'fds_Q'))
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            try
                ccc=unique(sort(cell_array)); 
                cnames = categorical(ccc);
                sz=size(ccc);
                app.msz=max(sz);
            catch
                app.msz=0;
            end
            
            app.kflag=0;
            nflag=0;

            if(sz(1)==0 || sz(2)==0)
                app.kflag=1;
                % warndlg('Ref 1:  No arrays available');
                return;
            end             
            
            if(app.msz<num_arrays)
                num_arrays=app.msz;
                try
                    app.NumberofFDSArraysListBox.Value=sprintf('%d',num_arrays);
                    warndlg('Number of curves reset to available number');
                catch
                end
            end 

            sq{1}=sprintf(' ');
            sb{1}=sprintf(' ');
            for i=2:num_arrays
                sq{end+1}=sprintf(' ');
                sb{end+1}=sprintf(' ');
            end
            sq=sq';
            sb=sb';

            if(num_arrays==1)
                try
                    n=1;
                    for i=1:app.msz
                        if(strcmp(app.name(1),char(cnames(i)))==1)
                            n=i;
                            nflag=1;
                            break;
                        end    
                    end    
                catch    
                end    
                cx={cnames(n)};       
            end    
            if(num_arrays==2)
                try
                    n=ones(2,1);
                    for ijk=1:2
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2))};    
            end   
            if(num_arrays==3)
                cx={cnames(end);cnames(end);cnames(end)};     
            end    
            
            if(num_arrays==4)
                try
                    n=ones(4,1);
                    for ijk=1:4
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2));cnames(n(3));cnames(n(4))};
            end     
            if(num_arrays==5)
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};
            end       
            if(num_arrays==6)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};          
            end 
            if(num_arrays==7)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};             
            end             
            if(num_arrays==8)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};              
            end  
            if(num_arrays==9)          
                cx={cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end);cnames(end)};              
            end  

            app.UITable_FDS_PSD.ColumnWidth={235,80,80};           

            try
                tdata = table(cx,sq,sb,'VariableNames',{'FDS Array Name','Q','b'});
            catch

                fprintf('\n  app.num=%d \n',app.num)
                warndlg('Change Rows: No arrays found');
            end

            try
                app.UITable_FDS_PSD.Data=tdata;  
            catch
            end

            if(nflag==1)
                extractbQ(app);
            end

        end


        function extractbQ(app)
 
            N=str2num(app.NumberofFDSArraysListBox.Value);
            app.AData=app.UITable_FDS_PSD.Data;

            G=app.AData;

            GG=table2cell(G);

            for i=1:N
                app.FDS_name{i}=char(GG{i,1});
            end

            sq{1}=sprintf(' ');
            sb{1}=sprintf(' ');
            for i=2:N
                sq{end+1}=sprintf(' ');
                sb{end+1}=sprintf(' ');
            end
            sq=sq';
            sb=sb';

            for i=1:N
                for j=100:-1:1
                    ss=sprintf('Q%d',j);
                    if(contains(app.FDS_name{i},ss))
                        ss=sprintf('%d',j);
                        G{i,2}={ss};
                        break;
                    end    
                end
            end    

            for i=1:N
                for j=200:-1:1
                    jj=j/10;
                    ss=sprintf('b%g',jj);
                    if(contains(app.FDS_name{i},ss))
                        ss=sprintf('%d',jj);
                        G{i,3}={ss};
                        break;
                    end    
                end
            end                

            try
                app.UITable_FDS_PSD.Data=G;  
            catch
                warndlg('Table failed')
                return;
            end    
        end

        function Tones(app)
            Items = strtrim(app.NumberofSinesListBox.Items);
            im = strtrim(app.NumberofSinesListBox.Value);
            app.num_tones = find(strcmp(Items, im));
            
            data_s = cell(app.num_tones, 1);
            data_s(:) = {''};
 
            app.UITableSine.Data=data_s; 
        end    
        
        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CloseFigures(app);
            Tones(app);
            cleartext(app);
            ChangeRows_FDS_PSD(app);
            app.WorkspaceArraysTextArea.Value='3';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            Closereq;
        end

        % Value changed function: NumberofSinesListBox
        function NumberofSinesListBoxValueChanged(app, event)
            Tones(app);
        end

        % Button pushed function: ExtractQbfromArrayNameButton
        function ExtractQbfromArrayNameButtonPushed(app, event)
            extractbQ(app);
        end

        % Value changed function: NumberofFDSArraysListBox
        function NumberofFDSArraysListBoxValueChanged(app, event)
            ChangeRows_FDS_PSD(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';       
            % app.ExportOutputsButton.Visible='off';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cleartext(app);

            app.MsgEditField.Value='a';

            CloseFigures(app);

            time_scale=6;

            Items = strtrim(app.NumberofSinesListBox.Items);
            im = strtrim(app.NumberofSinesListBox.Value);
            app.num_tones = find(strcmp(Items, im));

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            fig_num=1;     

            tpi=2*pi;


            num_sine=app.num_tones;
            
            ff=zeros(num_sine,1);
            smax=zeros(num_sine,1);
            
            try
                AS=app.UITableSine.Data;
            catch
                warndlg('1. Sine data not read');
                return;        
            end

            try
                
                if(isempty(AS))
                    warndlg('2. Sine data not read');
                    return;        
                end
             
                AS=char(AS);
            
                for i=1:num_sine
                    ff(i) = str2double(strtrim(AS(i,:)));
                end 
                xfs=ff;
                fs_samp=ff;
            catch
                warndlg('Sine data not read');
                return;
            end

            % key=app.IncludeSMCS016MinimumWorkmanshipCheckBox.Value;

            app.ntrials=str2num(app.NumberofTrialsEditField.Value);
            app.T_out=str2num(app.PSDDurationsecEditField.Value);

            ssc=str2num(app.MaxSlopedBoctEditField.Value);
            FDSEnvSOR.slopec=ssc;

            app.slopec=(ssc/10)/log10(2);

            FDSEnvSOR.ntrials=app.ntrials;
            FDSEnvSOR.T_out=app.T_out;
            
            Items = char(app.InputFDSTypeListBox.Items);
            im=app.InputFDSTypeListBox.Value;
            [app.nmetric]=find_index(Items,im);
            FDSEnvSOR.nmetric=im;

            Items = char(app.OutputPSDUnitListBox.Items);
            im=app.OutputPSDUnitListBox.Value;
            [app.iu]=find_index(Items,im);
            FDSEnvSOR.iu=im;

            % Items = char(app.OptimizationGoalListBox.Items);
            % im=app.OptimizationGoalListBox.Value;
            % [app.goal]=find_index(Items,im);
            
            im=1;

            % Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
            % im=app.PlateauMinimumOctaveSpacingListBox.Value;
            % [app.ip]=find_index(Items,im);
            % FDSEnvSOR.ip=im;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            im=app.NumberofFDSArraysListBox.Value;
            N=str2num(app.NumberofFDSArraysListBox.Value);
            FDSEnvSOR.N=im;

            app.Q=zeros(N,1);
            app.bex=zeros(N,1);
            
            app.AData=app.UITable_FDS_PSD.Data;
            FDSEnvSOR.AData=app.AData;

            A=table2cell(app.UITable_FDS_PSD.Data);

            clear app.Q;
            clear app.bex;
            
            for i=1:N
                app.FDS_name{i}=char(A{i,1});
            end

            for i=1:N
                try
                    app.Q(i)=str2num(strtrim(A{i,2}));
                catch
                end
            end

            for i=1:N
                try
                    app.bex(i)=str2num(strtrim(A{i,3}));
                catch
                end
            end
                
            for i=1:N
                data_s{i,1}=app.FDS_name{i};
                data_s{i,2}=sprintf('%g',app.Q(i));
                data_s{i,3}=sprintf('%g',app.bex(i));      
            end
            
            for i=1:N
            
                try
                    FS=app.FDS_name{i};
                    try
                        aq=evalin('base',FS);  
                    catch
                        try
                            FSS=sprintf('%s.txt',FS);
                            aq=readmatrix(FSS);
                            assignin('base',FS,aq)
                            aq=evalin('base',FS); 
                        catch
                            out1=sprintf('FDS array not found: %d %s',i,FS);
                            warndlg(out1);
                            return; 
                        end    
                    end

                    if(i==1)
                        app.fmin=aq(1,1);
                        app.fmax=aq(end,1);
                    end    

                catch
                    out1=sprintf('FDS array not found: %d %s',i,FS);
                    warndlg(out1);
                    return; 
                end
                
                if(isempty(aq))
                    warndlg('FDS array is empty');       
                    return;
                end
                
                if(i==1)
                    fn=aq(:,1);
                    n_ref=length(fn);
                    fds_ref=zeros(n_ref,N);
                end
                
                if(length(aq(:,2))~=n_ref)
                    warndlg('FDS length error');
                    return;
                end
                
                try
                   for k=1:n_ref
                        fds_ref(k,i)=aq(k,2);
                   end
                catch
                    warndlg('FDS array error');        
                    return;         
                end
                
            end               

            try
                f1=fn(1);
                f2=fn(end);
            catch
                warndlg('fn failed');
                return;
            end
            

            sr=ceil(10*fn(end));
            dt=1/sr;
            np=round(app.T_out/dt);
            npt=round(np/time_scale);
            
            tt=(0:(np-1))*dt;
            tt=tt';

            key=0;

            if(key==1)

                %
                % Interpolate SMC-S-016
                %
    
                spsd=[  20 	  0.0053 
                        150   0.04 
                        800   0.04 
                        2000  0.0064];                 
    
                [fi,sai] = interpolate_PSD_arbitrary_frequency(spsd(:,1),spsd(:,2),fn);        

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.NumberofPSDCoordinatesListBox.Items);
            im=app.NumberofPSDCoordinatesListBox.Value;
            [app.npb]=find_index(Items,im);
            
            if(app.npb==1)
                nbreak=2;
            end
            if(app.npb==2)
                nbreak=3;    
            end
            if(app.npb==3)
                nbreak=3;    
            end
            if(app.npb==4)
                nbreak=4;    
            end
            if(app.npb==5)
                nbreak=4;    
            end
            if(app.npb==6)
                nbreak=5;    
            end
            if(app.npb==7)
                nbreak=5;    
            end
            if(app.npb==8)
                nbreak=6;    
            end
            if(app.npb==9)
                nbreak=5;    
            end
            if(app.npb==10)
                nbreak=4;    
            end
            if(app.npb==11)
                nbreak=3;    
            end
            if(app.npb==12)
                nbreak=8;    
            end
            if(app.npb==13)
                nbreak=10;    
            end
            if(app.npb==14)
                nbreak=length(fn);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            damp=zeros(N,1);
            
            for i=1:N
                damp(i)=1/(2*app.Q(i));
                
                if(damp(i)>=1.0e-10 && damp(i)<=0.5)
                else
                    warndlg('Q value error');
                    fprintf(' i=%d damp=%8.4g  Q=%8.4g \n',i,damp(i),app.Q(i));
                    return;
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
 
            xf=zeros(nbreak,1);
            xapsd=zeros(nbreak,1);
            %         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
            initial=2;
            final=2;
            
            disp(' ');
            disp(' Generate sample PSDs');
            disp(' ');
            %
            progressbar;
            
            NLL=round(app.ntrials/2);
            if(NLL<10)
                NLL=10;
            end
            
            if(app.ip==1)
                plateau=1;
            end
            if(app.ip==2)
                plateau=2/3;
            end
            if(app.ip==3)
                plateau=1/2; 
            end
            if(app.ip==4)
                plateau=1/3; 
            end
            if(app.ip==5)
                plateau=1/4; 
            end

            if(app.npb==14)
                plateau=0;
            end  

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.iu==1)
                fprintf('\n  Sine Freq & Accel (G) \n\n');
            end
            if(app.iu==2)
                fprintf('\n  Sine Freq & Amp (unit) \n\n');    
            end

            n=length(app.bex);

            for ik=1:num_sine
                
                sr=0;
                
                [~,ii]=min(abs(ff(ik)-fn));
                cy=ff(ik)*app.T_out;
                 
                for i=1:n
                    s=(fds_ref(ii,i)/cy)^(1/app.bex(i));
                    s=s/app.Q(i);
            %        fprintf('%d Q=%g b=%g ff=%8.4g fn=%8.4g cy=%8.4g fds=%8.4g  s=%7.3g\n',i,Q(i),bex(i),ff(ik),fn(ii),cy,fds_ref(ii,i),s);
                    if(s>sr)
                        sr=s;
                    end
                end 
                
                smax(ik)=0.5*sr;   
                
                fprintf(' %8.4g  %8.4g  \n',ff(ik),smax(ik));      
                 
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            
            % generate white noise
                    
                    
            m_choice=1; % mean removal
            h_choice=1; % rectangular window

            tmax=np*dt;
            df=1/(npt*dt);
                    
            [white_noise,~]=white_noise_and_fft(tmax,m_choice,h_choice,np,dt);
            YF = fft(white_noise,np);

            total_rec=1.0e+90;

            plateau=1/4;
            
            for ik=1:app.ntrials
                progressbar(ik/app.ntrials);
          
           
                    if(rand()>0.7 || ik<5)
                %	   
                        % Generate the sample psd
                        [f_sam,apsd_sam,~,app.slopec,sine_samp]=...
                            env_generate_sample_psd_sine(n_ref,nbreak,app.npb,fn,app.slopec,initial,final,f1,f2,plateau,smax);
                        app.kflag=1;
                %
                    else
                %
                        if(rand()>0.5)
                            [f_sam,apsd_sam,~,app.slopec,sine_samp]=...
                                env_generate_sample_psd2_sine(nbreak,app.npb,xf,xapsd,app.slopec,f1,f2,plateau,xsine);
                            app.kflag=2;
                            
                        else   
                             [f_sam,apsd_sam,max_sss,app.slopec,fs_samp,sine_samp]=...
                                env_generate_sample_psd3_sine(nbreak,app.npb,xf,xapsd,app.slopec,f1,f2,plateau,xfs,xsine);
                             app.kflag=3;
                        end   
                %
                    end
                
                %
                %     freq=f_sam;integ
                %    amp=apsd_sam;
    
                %
                %      Interpolate the sample psd
                
                    if(app.npb<=13)
                        [fn,apsd_samfine]=env_interp_sam(f_sam,apsd_sam,nbreak,n_ref,fn);
                    else
                        fn=f_sam;
                        apsd_samfine=apsd_sam;
                    end
                % 
                    a11=max(apsd_samfine);
                    a22=min(apsd_samfine);
                    
                    if(a22<1.0e-20)
                        fprintf('\n max(apsd_samfine)=%8.4g  min(apsd_samfine)=%8.4g \n\n',a11,a22);
                        return;
                    end

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %   Synthesize time history for sample sine

                    freq=f_sam;
                    amp=apsd_sam;
                    
                    [slope,grms] = calculate_PSD_slopes_no(freq,amp);    
                    [~,spec]=interpolate_PSD_spec_notext(npt,freq,amp,slope,df);
                %
                    app.fmax=freq(end);
                
                %   Synthesize time history for sample sine
                
                    ssine=zeros(npt,1);
                    for i=1:num_sine
                         ssine=ssine+sine_samp(i)*sin(tpi*fs_samp(i)*tt(1:npt));
                    end    

                %   Synthesize time history for sample PSD
                %
                    sq_spec=sqrt(spec);
                    mmm=npt;
                %
                    [~,psd_th,~]=PSD_syn_FFT_core_notext(mmm,npt,app.fmax,df,sq_spec(1:npt),YF(1:npt));

                
                    sor_th=psd_th*(grms/std(psd_th))+ssine;  
    
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    [fds_th]=env_fds_batch_th_nop([tt(1:npt) sor_th(1:npt)],fn,app.Q,app.bex,dt);
                    fds_th=fds_th*time_scale;
                    
                %    
                %      Compare the sample fds with the reference fds
                    [scale]=env_compare_alt(n_ref,fds_ref,fds_th,app.bex);

                    
                %
                %      scale the time history psd
                
                    sor_th=sor_th*scale;
                    sine_samp=sine_samp*scale;
                    apsd_sam=apsd_sam*scale^2;

                    [fds_th]=env_fds_batch_th_nop([tt(1:npt) sor_th(1:npt)],fn,app.Q,app.bex,dt);
                    fds_th=fds_th*time_scale;
                    
                    max_dB=0;
                    dB_diff=0;
                    for j=1:n_ref
                        for k=1:length(app.bex)
                            term=abs(log10(fds_th(j,k)/fds_ref(j,k)));
                            dB_diff=dB_diff+ term;
                            if(term>max_dB)
                                max_dB=term;
                            end
                        end
                    end        
                
                %    [grms_psd]=env_grms_sam(nbreak,f_sam,apsd_sam);
                    
                   %  grms=(grms_psd^2*std(sor_th))^(1/3);
                   
                %    dB_diff=dB_diff*max_dB*grms;
                    
                     [v]=integrate_function(sor_th,dt);
                      v = 386*detrend( v , 1 );
                      vrms=std(v);
                    
                  %    [d]=integrate_function(v,dt);
                  %    d = detrend( d , 1 );
                  %  drms=std(d);    
                    
                 %   [vrms]=env_vrms_sam(nbreak,f_sam,apsd_sam);
                    total_diff=std(v)*std(sor_th)*dB_diff;
                    
                %       
                %      calculate the grms value 
                %             
                %    [grms]=env_grms_sam(nbreak,f_sam,apsd_sam);
                %    [vrms]=env_vrms_sam(nbreak,f_sam,apsd_sam);
                %    [drms]=env_drms_sam(nbreak,f_sam,apsd_sam);
                %	 
                %    [iflag,record]=env_checklow2(grms,vrms,drms,grmslow,vrmslow,drmslow,record,goal,apsd_sam);
                %
                
                    [max_sss,min_fff]=freq_slope_check(f_sam,apsd_sam);
                
                    if( (total_rec>total_diff && max_sss<app.slopec && min_fff>(1/3)) || ik==1)      
                %
                  %      dB_rec=dB_diff;
                  
                  
                        total_rec=total_diff;
                
                %          if(drms<drmslow)
                %              drmslow=drms;
                %          end
                %  		if(vrms<vrmslow)
                %  			vrmslow=vrms;   
                %          end
                %  		if(grms<grmslow)
                %  			grmslow=grms;
                %         end
                %
                        f_sam=fix_size(f_sam);
                        apsd_sam=fix_size(apsd_sam);
                
                        xf=f_sam;
 		                xapsd=apsd_sam;
                        xsine=sine_samp;
                        xfs=fs_samp;    
                        xfds=fds_th;

                        xaccel_th=sor_th;

                        
                %        drms_rec=drms;
                %        vrms_rec=vrms;
                %        grms_rec=grms;
                 
                        fprintf('\n Trial %ld, PSD Coordinates  kflag=%d \n\n',ik,app.kflag);
                       
                        if(app.iu==1)
                            disp('  Freq(Hz)  Accel(G^2/Hz) ');
                        else
                            disp('  Freq(Hz)  Amp(unit^2/Hz) ');            
                        end
                        
                        for i=1:nbreak
                            fprintf(' %8.2f \t %8.4g \n',xf(i),xapsd(i));
                        end
                        
                        if(app.iu==1)
                            fprintf('\n Sine Coordinates \n  Freq(Hz)  Accel(G) \n');
                        else
                            fprintf('\n Sine Coordinates \n  Freq(Hz)  Amp(unit) \n');            
                        end
                        
                        for i=1:num_sine
                            fprintf(' %8.2f \t %8.4g \n',xfs(i),xsine(i));            
                        end
                        
                        disp(' ');
                        % fprintf('scale=%8.4g \n',scale);
                        
                %
                        if(app.iu==1)
                            fprintf('   Trial: total_diff=%8.4g  grms=%8.4g  \n',total_rec,std(sor_th));  
                        else
                            fprintf('   Trial: total_diff=%8.4g  rms=%8.4g  \n',total_rec,std(sor_th));             
                        end
                            
                    if(min(fs_samp)<1.0)
                        warndlg('fs_samp error ref 4a');
                        disp('fs_samp error ref 4a ');
                        app.kflag
                        fs_samp;
                        return;
                    end 
                    if(min(xfs)<1.0)
                        warndlg('fs_samp error ref 4b');
                        disp('fs_samp error ref 4b ');
                        app.kflag
                        xfs
                        return;
                    end
                        
                %%%        out1=sprintf(' %8.4g %8.4g \n\n',max_sss,slopec);
                %%%       disp(out1);

                end 

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %  Post

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % xf=round(xf);
            
            pause(0.3);
            progressbar(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.power_spectral_density=[xf,xapsd];                
          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
        %
        %  total number of steps
        %
            num=np;
        %
        %  define time vector
        %
            t=tt;
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %  Synthesize sine time history
        %
            f=xfs;
            amp=xsine;
        %
            number_sines=length(f);
        %
        %  The sine_synth array stores the amplitude at each time step
        %
            sine_synth=zeros(num,1);
        %
            for i=1:number_sines
                sine_synth=sine_synth+amp(i)*sin(tpi*f(i)*t);
            end
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
            [psd_synth]=synthesize_psd(app.power_spectral_density,num,dt);
        %
            a=sine_synth+psd_synth(1:num);
            [v]=integrate_function(a,dt);
            v = 386*detrend( v , 1 );
            [d]=integrate_function(v,dt);
            d = 386*detrend( d , 1 );  

            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);

            if(app.iu==1)
            
                ay='Accel (G)';
                vy='Vel (in/sec)';
                dy='Disp (in)';
            
                aat='Acceleration';
                vvt='Velocity';
                ddt='Displacement';
            
                [fig_num,hp]=plot_avd_time_histories_subplots_altp_titles(fig_num,tt,a,v,d,ay,vy,dy,aat,vvt,ddt);
            
            end
            
            %
            disp('_____________________________________________________________________');
            %
            %
            % disp('Optimum Case   (copy and paste into Excel)');
            % disp(' ');

            sq=sprintf(' Optimum Case \n');
            
            if(app.iu==1)
                s1=sprintf(' PSD: \n Freq(Hz) \t Accel(G^2/Hz)  \n');
            end
            if(app.iu==2)
                s1=sprintf(' PSD: \n Freq(Hz) \t Accel(unit^2/Hz)  \n');    
            end

            sq=sprintf('%s\n%s',sq,s1);
                
            for i=1:nbreak
                s2=sprintf('    %5.3e   %5.3e  \n',app.power_spectral_density(i,1),app.power_spectral_density(i,2));
                sq=sprintf('%s%s',sq,s2);
            end

            
            if(app.iu==1)
                s3=sprintf('\n Sine: \n Freq(Hz) \tAccel(G)  \n');
            end
            if(app.iu==2)
                s3=sprintf('\n Sine: Freq(Hz) \tAmp(unit)  \n');
            end
            sq=sprintf('%s\n%s',sq,s3);

                
            for ik=1:num_sine 
                s4=sprintf('       %g \t %8.4g  \n',xfs(ik),xsine(ik));  
                sq=sprintf('%s%s',sq,s4);
            end

            sq=sprintf('%s\n',sq);
            
            if(app.iu==1)
                s5=sprintf(' Overall levels \n     disp=%10.4g in rms  \n velocity=%10.4g in/sec rms \n    accel=%10.4g G rms \n',std(d),std(v),std(a));
            end
            if(app.iu==2)
                s5=sprintf(' Overall level \n %10.4g rms \n',std(a));
            end

             sq=sprintf('%s\n%s',sq,s5);
            
            ppp1=app.power_spectral_density;
            ppp2=[xfs xsine];
            
            if(app.iu==1)
                tstring=sprintf(' Sine-on-Random Level  %7.3g GRMS overall',std(xaccel_th));
            end
            if(app.iu==2)
                tstring=sprintf(' Sine-on-Random Level  %7.3g RMS overall',std(xaccel_th));    
            end
            
            md=5;
            
            
            if(app.iu==1)
                [fig_num]=plot_double_y_SOR(fig_num,ppp1,ppp2,tstring,app.fmin,app.fmax,md);
            else
                [fig_num]=plot_double_y_SOR_other(fig_num,ppp1,ppp2,tstring,app.fmin,app.fmax,md);   
            end
                
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            % disp(' ');
            % disp('  PSD output arrays:  psd_envelope ');
            % disp(' ');
            
            assignin('base','psd_envelope',app.power_spectral_density);
            
            %
            x_label=sprintf(' Natural Frequency (Hz)');
            ylab='Damage Index';
            %
           
            sx=sprintf(' FDS output arrays \n\n');
           
            %
            
            uiu=(length(unique(damp))+length(unique(app.bex)));

            for i=1:length(damp)
                
            %
                    xx=zeros(n_ref,1);
                    ff=zeros(n_ref,1);
            %        
                    for k=1:n_ref
                        xx(k)=xfds(k,i);
                        ff(k)=fds_ref(k,i);
                    end
            %
                    xx=fix_size(xx);
                    ff=fix_size(ff);
                    fn=fix_size(fn);
            %
                    fds1=[fn xx];
                    fds2=[fn ff];
            %
            %%%%%%%%%%%
            %
                    sbex=sprintf('%g',app.bex(i));
                    sbex=strrep(sbex, '.', 'p');
                    output_name=sprintf('sor_fds_Q%g_b%s',app.Q(i),sbex);
                    output_name2=sprintf('    %s',output_name);
                    assignin('base', output_name, fds1);
                    sx=sprintf('%s%s\n',sx,output_name2);
            %
            %%%%%%%%%%%
            %       
            %        output_name_ref=sprintf('time_history_fds_Q%g_b%s',app.Q(i),sbex);
            %        assignin('base', output_name_ref, fds2);
            
            %        output_name3=sprintf('    %s',output_name_ref);
            %        sx=sprintf('%s%s\n',sx,output_name3);
            %
            %%%%%%%%%%%
            %
                    if(uiu~=4)
                        leg_a=sprintf('Sine-on-Random Envelope');
                        leg_b=sprintf('Measured Data');
            %
                        [fig_num,h3]=...
                        plot_fds_two_h2(fig_num,x_label,ylab,fds1,fds2,leg_a,leg_b,app.Q(i),app.bex(i),app.iu,app.nmetric);    
                    
                    end
            %
                
            end

            sx=sprintf('%s\n',sx);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(uiu==4)

                [fig_num,h3]=fds_plot_2x2_alt_h2(fig_num,app.Q,app.bex,fn,ff,xx,xfds,fds_ref,app.nmetric,app.iu);        
                        
            end    

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                   
            disp(' ');
            disp(' The fatigue damage spectra is calculated from the amplitude (peak-valley)/2 ');
            
            setappdata(0,'power_spectral_density',app.power_spectral_density); 
            
            output_name=strtrim(app.OptimizedArrayOuputNamePrefixEditField.Value);
            
            if(isempty(output_name))
                warndlg('Enter output name');
                return;
            end    

            % assignin('base', output_name, data);
            
            output_name_psd=sprintf('%s_psd',output_name);
            assignin('base', output_name_psd,app.power_spectral_density);
            
            output_name_sine=sprintf('%s_sine',output_name);
            assignin('base', output_name_sine, [xfs xsine]);
            
            output_name_th=sprintf('%s_th',output_name);
            output_name_th=strrep(output_name_th,'psd','');
            assignin('base', output_name_th, [tt a]);
            
           
            s10=sprintf(' Output files \n\n');
            s11=sprintf('         psd: %s \n',output_name_psd);
            s12=sprintf('        sine: %s \n',output_name_sine);
            s13=sprintf('time history: %s \n\n',output_name_th);

            sx=sprintf('%s\n%s%s%s%s',sx,s10,s11,s12,s13);
           
            app.WorkspaceArraysTextArea.Value=sx;

            disp(' ')
            disp(sq);
            disp(' ')
            disp(sx);

            app.ResultsTextArea.Value=sq;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            app.TabGroup.SelectedTab=app.TabGroup.Children(4);
          
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)

            try
                app.AData=app.UITable_FDS_PSD.Data;
                FDSEnvSOR.AData=app.AData;
            catch
            end

            try
                im=app.NumberofFDSArraysListBox.Value;
                N=str2num(app.NumberofFDSArraysListBox.Value);
                FDSEnvSOR.N=im;
            catch
            end

            try
        
                A=table2cell(app.AData);
            
                for i=1:FDSEnvSOR.N
                    app.FDS_name{i}=char(A{i,1});
                end
            catch
            end

            try
                Items = char(app.NumberofPSDCoordinatesListBox.Items);
                im=app.NumberofPSDCoordinatesListBox.Value;
                [app.npb]=find_index(Items,im);
                FDSEnvSOR.npb=im;         
            catch
            end

            try
                Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
                im=app.PlateauMinimumOctaveSpacingListBox.Value;
                [app.ip]=find_index(Items,im);
                FDSEnvSOR.ip=im;
            catch
            end
            try
                ssc=str2num(app.MaxSlopedBoctEditField.Value);
                FDSEnvSOR.slopec=ssc;
            catch
            end

            try
                app.ntrials=str2num(app.NumberofTrialsEditField.Value);
                FDSEnvSOR.ntrials=app.ntrials;            
            catch
            end

            try
                app.T_out=str2num(app.PSDDurationsecEditField.Value);
                FDSEnvSOR.T_out=app.T_out;            
            catch
            end    

            try
                Items = char(app.InputFDSTypeListBox.Items);
                im=app.InputFDSTypeListBox.Value;
                [app.nmetric]=find_index(Items,im);
                FDSEnvSOR.nmetric=im;            
            catch
            end  

            try
                Items = char(app.OutputPSDUnitListBox.Items);
                im=app.OutputPSDUnitListBox.Value;
                [app.iu]=find_index(Items,im);
                FDSEnvSOR.iu=im;            
            catch
            end  

            try
                Items = char(app.OptimizationGoalListBox.Items);
                im=app.OptimizationGoalListBox.Value;
                [app.goal]=find_index(Items,im);
                FDSEnvSOR.goal=im;            
            catch
            end

            try
                FDSEnvSOR.name=app.FDS_name;
            catch
            end

            try
                sz=size(FDSEnvSOR.name);

                for i=1:sz(1)
                    FS=FDSEnvSOR.name{i};
                    writefname=sprintf('%s.mat',FS);

                    try
                        save_mat(writefname, writepname,FS)
                    catch
                    end
                end               
            catch
            end


            try
                sine=app.UITableSine.Data;
                FDSEnvSOR.sine=sine;
            catch
            end
            try
                num_sine=app.NumberofSinesListBox.Value;
                FDSEnvSOR.num_sine=num_sine;
            catch
            end
            

            % % %
             
             structnames = fieldnames(FDSEnvSOR, '-full'); % fields in the stru
              
            % % %
             
                [writefname, writepname] = uiputfile('*.mat','Save data as');
               
                elk=sprintf('%s%s',writepname,writefname);
            
            try
                save(elk, 'FDSEnvSOR'); 
            catch
                warndlg('Save error');
                return;
            end
             
            disp(' ');
            out1=sprintf('Save Complete: %s',writefname);
            disp(out1);
            msgbox(out1);
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
                        
            [filename, pathname] = uigetfile('*.mat', 'Select save file');

            NAME = [pathname,filename];
            
            try

                try
                    struct=load(NAME);
                    structnames = fieldnames(struct, '-full'); % fields in the struct        
                    k=length(structnames);
                catch
                    NAME
                    disp('NAME fail');
                end
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end

            catch
                warndlg('load failed')
                return;
            end
            
            % struct
            
            try
               FDSEnvSOR=evalin('base','FDSEnvSOR');
            catch
               warndlg(' evalin failed 2');
               return;
            end
            
            %%%%%%%%%%%%

            try
                app.NumberofFDSArraysListBox.Value=sprintf('%s',FDSEnvSOR.N);
            catch
            end

            try
                app.OutputPSDUnitListBox.Value=sprintf('%s',FDSEnvSOR.iu);
            catch         
            end

            try
                app.OptimizationGoalListBox.Value=sprintf('%s',FDSEnvSOR.goal);
            catch  
            end

            try
                app.PlateauMinimumOctaveSpacingListBox.Value=sprintf('%s',FDSEnvSOR.ip);
            catch  
            end

            try
                app.NumberofPSDCoordinatesListBox.Value=sprintf('%s',FDSEnvSOR.npb);
            catch  
            end

            %%%%%%%%%%%%

            try 
                app.NumberofTrialsEditField.Value=sprintf('%d',FDSEnvSOR.ntrials);
            catch
            end 

            try 
                app.PSDDurationsecEditField.Value=sprintf('%g',FDSEnvSOR.T_out);
            catch 
            end 

            try 
                app.MaxSlopedBoctEditField.Value=sprintf('%g',FDSEnvSOR.slopec);
            catch
            end   

            try
                ChangeRows_FDSEnvSOR(app);
            catch   
            end


            try
                app.UITableSine.Data=FDSEnvSOR.sine;
            catch
            end
            try
                app.NumberofSinesListBox.Value=FDSEnvSOR.num_sine;
            catch
            end


            try
                nnn=char(FDSEnvSOR.name)

                sz=size(nnn)

                try
    
                    for i=1:sz(1)
                        
                        fname=nnn(i,:)
                                
                        try
                            aa=evalin('base',fname);   
                        catch
                            try
                                load_mat(writepname,fname);
                            catch    
                                disp('load failed')
                            end    
                        end
                              
                    end

                catch
                    disp('loop failed')
                end

                disp('pass')
            catch
                disp('fail')
            end   

            try
                ChangeRows_FDS_PSD(app);
            catch
            end


            try
                app.UITable_FDS_PSD.Data=FDSEnvSOR.AData;
            catch
                % warndlg('Table Failed')
            end

            msgbox('Load Complete')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'envelope_fds_sine_a   ver 4.0  by Tom Irvine        This script derives a PSD plus sine envelope for a FDS';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnterFDSDataTab = uitab(app.TabGroup);
            app.EnterFDSDataTab.Title = 'Enter FDS Data';
            app.Label_2 = uilabel(app.EnterFDSDataTab);
            app.Label_2.Text = 'Each needs to have the same natural frequency vector.  Q = amplification factor      b = fatigue exponent';
            app.InputFDSTypeListBoxLabel = uilabel(app.EnterFDSDataTab);
            app.InputFDSTypeListBoxLabel.Text = 'Input FDS Type';
            app.InputFDSTypeListBox = uilistbox(app.EnterFDSDataTab);
            app.InputFDSTypeListBox.Items = {'Absolute Acceleration', 'Other'};
            app.InputFDSTypeListBox.Value = 'Absolute Acceleration';
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel = uilabel(app.EnterFDSDataTab);
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel.Text = 'Double click on Array Name to access pull down menu.';
            app.UITable_FDS_PSD = uitable(app.EnterFDSDataTab);
            app.UITable_FDS_PSD.ColumnName = {'FDS Array Name'; 'Q'; 'b'};
            app.UITable_FDS_PSD.RowName = {};
            app.UITable_FDS_PSD.ColumnEditable = true;
            app.ExtractQbfromArrayNameButton = uibutton(app.EnterFDSDataTab, 'push');
            app.ExtractQbfromArrayNameButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractQbfromArrayNameButtonPushed, true);
            app.ExtractQbfromArrayNameButton.Text = 'Extract Q & b from Array Name';
            app.NumberofFDSArraysListBoxLabel = uilabel(app.EnterFDSDataTab);
            app.NumberofFDSArraysListBoxLabel.Text = {'Number of '; 'FDS Arrays'};
            app.NumberofFDSArraysListBox = uilistbox(app.EnterFDSDataTab);
            app.NumberofFDSArraysListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofFDSArraysListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofFDSArraysListBoxValueChanged, true);
            app.NumberofFDSArraysListBox.Value = '1';
            app.SelectSineTonesTab = uitab(app.TabGroup);
            app.SelectSineTonesTab.Title = 'Select Sine Tones';
            app.UITableSine = uitable(app.SelectSineTonesTab);
            app.UITableSine.ColumnName = {'Freq (Hz)'};
            app.UITableSine.RowName = {};
            app.UITableSine.ColumnEditable = true;
            app.NumberofSinesListBoxLabel = uilabel(app.SelectSineTonesTab);
            app.NumberofSinesListBoxLabel.Text = {'Number '; 'of Sines'};
            app.NumberofSinesListBox = uilistbox(app.SelectSineTonesTab);
            app.NumberofSinesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofSinesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofSinesListBoxValueChanged, true);
            app.NumberofSinesListBox.Value = '1';
            app.CalculateEnvelopeTab = uitab(app.TabGroup);
            app.CalculateEnvelopeTab.Title = 'Calculate Envelope';
            app.CalculateButton = uibutton(app.CalculateEnvelopeTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PSDDurationsecEditFieldLabel = uilabel(app.CalculateEnvelopeTab);
            app.PSDDurationsecEditFieldLabel.Text = 'PSD Duration (sec)';
            app.PSDDurationsecEditField = uieditfield(app.CalculateEnvelopeTab, 'text');
            app.PSDDurationsecEditField.Value = '60';
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculateEnvelopeTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculateEnvelopeTab, 'text');
            app.NumberofTrialsEditField.Value = '60';
            app.OutputPSDUnitListBoxLabel = uilabel(app.CalculateEnvelopeTab);
            app.OutputPSDUnitListBoxLabel.Text = 'Output PSD Unit';
            app.OutputPSDUnitListBox = uilistbox(app.CalculateEnvelopeTab);
            app.OutputPSDUnitListBox.Items = {'G^2/Hz', 'Unit^2/Hz'};
            app.OutputPSDUnitListBox.Value = 'G^2/Hz';
            app.NumberofPSDCoordinatesListBoxLabel = uilabel(app.CalculateEnvelopeTab);
            app.NumberofPSDCoordinatesListBoxLabel.Text = 'Number of PSD Coordinates';
            app.NumberofPSDCoordinatesListBox = uilistbox(app.CalculateEnvelopeTab);
            app.NumberofPSDCoordinatesListBox.Items = {'2 arbitrary', '3 arbitrary', '3 ramp-plateau', '4 arbitrary', '4 ramp-plateau-ramp', '5 arbitrary', '5 ramp-plateau-ramp-plateau', '6 ramp-plateau-ramp-plateau-ramp', '5 ramp-ramp-plateau-ramp', '4 ramp-ramp-plateau', ''};
            app.NumberofPSDCoordinatesListBox.Value = '2 arbitrary';
            app.RandomNumberSeedListBoxLabel = uilabel(app.CalculateEnvelopeTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.CalculateEnvelopeTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.OptimizedArrayOuputNamePrefixEditFieldLabel = uilabel(app.CalculateEnvelopeTab);
            app.OptimizedArrayOuputNamePrefixEditFieldLabel.Text = 'Optimized Array Ouput Name Prefix';
            app.OptimizedArrayOuputNamePrefixEditField = uieditfield(app.CalculateEnvelopeTab, 'text');
            app.OptimizedArrayOuputNamePrefixEditField.Value = 'optimized ';
            app.MaxSlopedBoctEditField_2Label = uilabel(app.CalculateEnvelopeTab);
            app.MaxSlopedBoctEditField_2Label.Text = 'Max Slope (dB/oct)';
            app.MaxSlopedBoctEditField = uieditfield(app.CalculateEnvelopeTab, 'text');
            app.MaxSlopedBoctEditField.Value = '9';
            app.ResultsExportsTab = uitab(app.TabGroup);
            app.ResultsExportsTab.Title = 'Results & Exports';
            app.ResultsTextAreaLabel = uilabel(app.ResultsExportsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ResultsExportsTab);
            app.WorkspaceArraysTextAreaLabel = uilabel(app.ResultsExportsTab);
            app.WorkspaceArraysTextAreaLabel.Text = 'Workspace Arrays';
            app.WorkspaceArraysTextArea = uitextarea(app.ResultsExportsTab);
            app.SaveModelButton = uibutton(app.UIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.UIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.MsgEditFieldLabel = uilabel(app.UIFigure);
            app.MsgEditFieldLabel.Text = 'Msg';
            app.MsgEditField = uieditfield(app.UIFigure, 'text');
            app.MsgEditField.Editable = 'off';
            app.MsgEditField.Enable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SaveModelButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = envelope_fds_sine_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end