classdef envelope_psd_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvelopePSDUIFigure            matlab.ui.Figure
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        InputPSDTab                    matlab.ui.container.Tab
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        CalculateTab                   matlab.ui.container.Tab
        RandomNumberSeedListBox        matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel   matlab.ui.control.Label
        FreqLimitsbyBreakpointExcludingEndPointsLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        FreqConstraintsListBox         matlab.ui.control.ListBox
        FreqConstraintsListBoxLabel    matlab.ui.control.Label
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        MaxSlopedBoctEditField         matlab.ui.control.EditField
        MaxSlopedBoctEditFieldLabel    matlab.ui.control.Label
        EndFreqHzEditField             matlab.ui.control.EditField
        EndFreqHzEditFieldLabel        matlab.ui.control.Label
        StartFreqHzEditField           matlab.ui.control.EditField
        StartFreqHzEditFieldLabel      matlab.ui.control.Label
        NumberofTrialsEditField        matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel   matlab.ui.control.Label
        PSDTypeListBox                 matlab.ui.control.ListBox
        PSDTypeListBoxLabel            matlab.ui.control.Label
        NumberofBreakpointsListBox     matlab.ui.control.ListBox
        NumberofBreakpointsListBoxLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ViewResultExportTab            matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        BestPSDOutputNameEditField     matlab.ui.control.EditField
        BestPSDOutputNameEditFieldLabel  matlab.ui.control.Label
        BestPSDTextArea                matlab.ui.control.TextArea
        BestPSDTextAreaLabel           matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        envelope_psd_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM      % Description
        npb      %
        nf       %
        nbreak   %
        method   %
        opt_psd  %        
    end
    
    methods (Access = private)

        function numberb(app)
        
            if(app.npb==1)
                app.nbreak=2;
            end
            if(app.npb==2)
                app.nbreak=3;    
            end
            if(app.npb==3)
                app.nbreak=3;    
            end
            if(app.npb==4)
                app.nbreak=4;    
            end
            if(app.npb==5)
                app.nbreak=4;    
            end
            if(app.npb==6)
                app.nbreak=5;    
            end
            if(app.npb==7)
                app.nbreak=5;    
            end
            if(app.npb==8)
                app.nbreak=6;    
            end
            if(app.npb==9)
                app.nbreak=8;    
            end
            if(app.npb==10)
                app.nbreak=3;    
            end
            if(app.npb==11)
                app.nbreak=6;    
            end
            if(app.npb==12)
                app.nbreak=7;    
            end
            if(app.npb==13)
                app.nbreak=10;    
            end
            if(app.npb==14)
                app.nbreak=12;    
            end
            if(app.npb==15)
                app.nbreak=14; 
            end
        end

        function clear(app)
            app.BestPSDTextArea.Visible='off';
            app.BestPSDTextAreaLabel.Visible='off';             
        end    

        function Number(app)
            
            app.FreqLimitsbyBreakpointExcludingEndPointsLabel.Visible='off';    
            app.UITable.Visible='off';

            Items = char(app.FreqConstraintsListBox.Items);
            im=app.FreqConstraintsListBox.Value;
            [app.nf]=find_index(Items,im);

            Items = char(app.NumberofBreakpointsListBox.Items);
            im=app.NumberofBreakpointsListBox.Value;
            [app.npb]=find_index(Items,im);

            %

            numberb(app);
            
            num=app.nbreak-2;
         
            if(app.nbreak>=3 && app.nf==1)
            
                app.FreqLimitsbyBreakpointExcludingEndPointsLabel.Visible='on';    
                app.UITable.Visible='on';
                
                for i=1:num
                    data_s{i,1}=sprintf('%d',i+1);
                    data_s{i,2}='';
                    data_s{i,3}='';          
                end
    
                app.UITable.Data=data_s;
                
            end
        end

        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
        end        

        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end        
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            Number(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end


            initial=2;
            final=2;    

            Items = char(app.PSDTypeListBox.Items);
            im=app.PSDTypeListBox.Value;
            [ntype]=find_index(Items,im);           
            
            Items = char(app.FreqConstraintsListBox.Items);
            im=app.FreqConstraintsListBox.Value;
            [nfc]=find_index(Items,im);           
            
            FL=zeros(app.nbreak,1);
            FU=zeros(app.nbreak,1);
            
            if(nfc==1 && app.nbreak>=3)
                
                data=app.UITable.Data;
                A=char(data); 
                N=app.nbreak-2;
                
                k=1;
            
                for i=1:N
                    array_name=A(k,:); k=k+1;
                    array_name=strtrim(array_name);
                    FL(i+1)=str2double(array_name);
                end
                for i=1:N
                    array_name=A(k,:); k=k+1;
                    array_name=strtrim(array_name);
                    FU(i+1)=str2double(array_name);
                end    
                
            end
            
            n=length(app.THM(:,1));
            
            if(app.THM(:,1)<1.0e-20)
                app.THM(1,:)=[];
            end
            
            sminf=app.StartFreqHzEditField.Value;
            smaxf=app.EndFreqHzEditField.Value;
            
            if isempty(sminf)
                string=sprintf('%8.4g',app.THM(1,1));
                app.StartFreqHzEditField.Value=string;
            end
            
            if isempty(smaxf)
                string=sprintf('%8.4g',app.THM(n,1));    
                app.EndFreqHzEditField.Value=string;
            end
            
            fmin=str2num(app.StartFreqHzEditField.Value);
            fmax=str2num(app.EndFreqHzEditField.Value);
            
            [~,ii]=min(abs(app.THM(:,1)-fmin));
            [~,jj]=min(abs(app.THM(:,1)-fmax));
            
            app.THM=app.THM(ii:jj,:);
            
            f1=fmin;
            f2=fmax;
            
            FL(1)=fmin;
            FU(1)=fmin;
            FL(app.nbreak)=fmax;
            FU(app.nbreak)=fmax;
            
            %%%%%%%%%%
            
            Q=str2num(app.AmplificationFactorQEditField.Value);
            
            dam=1/(2*Q);
            %
            MAX=20000;
            
            % nbreak   number of breakpoints
            % ntrials  number of trails
            % ntrials2
            % n_ref    number of reference coordinates
            %
            record = 1.0e+90;
            grmslow=1.0e+50;
            vrmslow=1.0e+50;
            drmslow=1.0e+50;
            %
            % f_ref[MAX]    reference natural frequency
            % a_ref[MAX]    reference vrs(GRMS)
            %
            % fin[MAX]      input frequency
            % psdin[MAX]    input PSD
            % inslope[MAX]  input slope
            %
            % interp_psdin[MAX] interpolated input PSD
            %
            % f_sam[MAX]    frequency of sample breakpoint
            % apsd_sam[MAX] acceleration PSD amplitude of sample breakpoint
            % slope[MAX]
            %
            % f_samfine[MAX]    frequency of sample breakpoint, interpolated
            % apsd_samfine[MAX] acceleration PSD of sample breakpoint, interpolated  
            %
            
            goal=3;  % Minimize: acceleration, velocty, displacement
            
            ntrials = floor(str2num(app.NumberofTrialsEditField.Value));
            
            %%%%  nbreak=4;  % number of breakpoints
            
            slope=zeros(app.nbreak,1);
            xf=zeros(app.nbreak,1);
            xapsd=zeros(app.nbreak,1);
            
            ic=1;  % constrain slope
            
            slopec=str2num(app.MaxSlopedBoctEditField.Value);
            slopec=abs(slopec);
            slopec=(slopec/10.)/log10(2.);
            
            %
            ocn=1/48;
            %
            octave=-1.+(2.^ocn);
            %
            %
             ffmin=f1;
             ffmax=f2;
             %
             if(ffmax<=1000)
                 ffmax=1000;
             end
              if(ffmax>1000 && ffmax<=2000)
                 ffmax=2000;
              end
            % 
            %
              fin=app.THM(:,1);
            psdin=app.THM(:,2);
            %
            if( min(psdin) <= 0. )
                warndlg(' Input error:  each PSD amplitude must be > 0.');
                return;
            end               
            %
            if( min(fin) < 0. )
                warndlg(' Input error:  each frequency must be > 0.');
                return;
            end               
            %
            if(fin(1)<1.0e-04)
                fin(1)=1.0e-04;
            end
            %
            clear length;
            nin = length(fin);
            %
            inslope=zeros((nin-1),1);
            %
            for i=2:nin
                inslope(i-1)= log(psdin(i)/psdin(i-1))/log(fin(i)/fin(i-1));
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,grms_input]= calculate_PSD_slopes(fin,psdin);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            fprintf('\n Number of input coordinates = %ld \n',nin);
            %
            % Interpolate Input PSD
            %
            
            f=fin;
            a=psdin;

            new_freq=round(fmin):round(fmax);

            [fi,ai] = interpolate_PSD_arbitrary_frequency(f,a,new_freq);

            n_ref=length(fi);
            
            interp_psdin=ai;
            f_ref=fi;

            %
            % Convert the input PSD to a VRS
            %  
                [a_ref]=env_make_input_vrs(interp_psdin,n_ref,f_ref,octave,dam);
                
                a_ref=fix_size(a_ref);
                
                input_vrs=[f_ref a_ref];
            %
            progressbar;
            
                nx=ntrials*0.3;
                
                if(nx<4)
                    nx=4;
                end
            
                for ik=1:ntrials
                    
                   progressbar(ik/ntrials);
            %      
                   if(rand()>0.7 || ik<nx)
            %      
                        % Generate the sample psd
                        
                        qflag=1;
                        
                        [f_sam,apsd_sam,~,slopec]=...
                            env_generate_sample_psd_via_vrs(n_ref,app.nbreak,app.npb,f_ref,slopec,initial,final,f1,f2,FL,FU,nfc);
            %          
                    else
            %         
                        qflag=2;
                        
                        [f_sam,apsd_sam,~,slopec]=...
                            env_generate_sample_psd2_via_vrs(app.nbreak,app.npb,xf,xapsd,slopec,f1,f2,FL,FU,nfc);            
             %
                   end
            %%
            %
            %      Interpolate the sample psd
                   [f_samfine,apsd_samfine]=env_interp_sam(f_sam,apsd_sam,app.nbreak,n_ref,f_ref);
            %                         
            %      Calculate the vrs of the sample psd
                   [vrs_samfine]=env_vrs_sample(n_ref,f_ref,octave,dam,apsd_samfine);
            %    
            %      Compare the sample vrs with the reference vrs
                   [scale]=env_compare_rms(n_ref,a_ref,vrs_samfine);
            %
            %      scale the psd
                   scale=(scale^2.);
                   apsd_sam=apsd_sam*scale*1.04;
            %       
            %      calculate the grms value 
            %             
                   [grms]=env_grms_sam(app.nbreak,f_sam,apsd_sam);
                   [vrms]=env_vrms_sam(app.nbreak,f_sam,apsd_sam);
                   [drms]=env_drms_sam(app.nbreak,f_sam,apsd_sam);
            %    
                   [iflag,record]=env_checklow(grms,vrms,drms,grmslow,vrmslow,drmslow,record,goal);
            %       
                   if(iflag==1)
            %           
                       if(drms<drmslow)
                            drmslow=drms;
                       end
                       if(vrms<vrmslow)
                            vrmslow=vrms;
                       end
                       if(grms<grmslow)
                            grmslow=grms;
                       end
            %           
                       drmsp=drms;
                       vrmsp=vrms;
                       grmsp=grms;
            %
                       xf=f_sam;
                       xapsd=apsd_sam;             
            %       
            % Interpolate the best psd
            %
                      f_ref=fix_size(f_ref);
            %         
                      [xapsdfine]=env_interp_best(app.nbreak,n_ref,xf,xapsd,f_ref);
            %
                      xapsdfine=fix_size(xapsdfine);
            %
                      xf=fix_size(xf); 
                      xapsd=fix_size(xapsd);
            %
                      best_psd=[xf xapsd];
            %
            % Calculate the vrs of the best vrs
            %
                      [xvrs]=env_vrs_best(n_ref,dam,octave,xapsdfine,f_ref);          
            %
                      sz=size(xvrs);
                      if(sz(2)>sz(1))
                         xvrs=xvrs';
                      end
            %
                      best_vrs=[f_ref xvrs];
            %     
                   
                    out1=sprintf('   Trial: drms=%10.4g  vrms=%10.4g  grms=%10.4g ',drms,vrms,grms); 
                    fprintf('  %d  Record: drms=%10.4g  vrms=%10.4g  grms=%10.4g %d %d \n',ik,drmsp,vrmsp,grmsp,iflag,qflag); 
             %       disp(out1);
                    
                   end  
            
            %
                end
                
                progressbar(1);
                
            %
                disp('________________________________________________________________________');
            %
                disp('Optimum Case');
                disp(' ');
            %
                out1=sprintf(' drms=%10.4g  vrms=%10.4g  grms=%10.4g ',drmsp,vrmsp,grmsp);
                disp(out1); 
            %   
                disp('________________________________________________________________________');
            %
             %
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             %
             fig_num=1;
             pmd=6;
             px_label='Frequency(Hz)';
             
             if(ntype==1)
                py_label='Accel (G^2/Hz)';
                pleg1=sprintf('Input, %6.3g GRMS',grms_input);
                pleg2=sprintf('Envelope, %6.3g GRMS',grmsp); 
                pt_string='Power Spectral Density';
             else
                py_label='Pressure (psi^2/Hz)';
                pleg1=sprintf('Input, %6.3g psi rms',grms_input);
                pleg2=sprintf('Envelope, %6.3g psi rms',grmsp);
                pt_string='Pressure Power Spectral Density';
             end
                
            ppp1=[fin psdin];
            ppp2=best_psd;
             
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,px_label,...
                           py_label,pt_string,ppp1,ppp2,pleg1,pleg2,fmin,fmax,pmd);
             %
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             % 
             qqq1=input_vrs;
             qqq2=best_vrs;
             
            if(ntype==1)
              qy_label='Accel (GRMS)';
              qt_string=sprintf('Vibration Response Spectra  Q=%g',Q);   
            else
              qy_label='Pressure (psi rms)';   
              qt_string=sprintf('VRS Transmitted Pressure  Q=%g',Q);   
            end
             
            qx_label='Natural Frequency(Hz)';
            qmd=5;
            %
            qleg1=pleg1;
            qleg2=pleg2;
              
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,qx_label,...
                           qy_label,qt_string,qqq1,qqq2,qleg1,qleg2,fmin,fmax,qmd);
                        
            [fig_num,h2]=subplots_two_and_two_h2(fig_num,...
                            px_label,py_label,pt_string,ppp1,ppp2,pleg1,pleg2,...
                            qx_label,qy_label,qt_string,qqq1,qqq2,qleg1,qleg2,...
                            fmin,fmax,pmd,qmd);      
            %%%%%%%%%%
            
            disp(' ');
            disp(' Best PSD ');
            disp(' ');
            if(ntype==1)
                ss=' Freq(Hz)  Accel(G^2/Hz) ';
                s5=sprintf('\n\n Overall %7.3g GRMS',grmsp);
            else
                ss=' Freq(Hz)  Pressure(psi^2/Hz) ';
                s5=sprintf('\n\n Overall %7.3g psi RMS',grmsp);             
            end
            disp(ss);
            ss=sprintf('%s\n\n',ss);

            for i=1:app.nbreak
                s2=sprintf(' %8.4g \t %7.3g \n',best_psd(i,1),best_psd(i,2));
                fprintf('%s',s2);
                ss=sprintf('%s %s',ss,s2);
            end            
            ss=sprintf('%s %s',ss,s5);           

            app.BestPSDTextArea.Value=ss;
            app.BestPSDTextArea.Visible='on';
            app.BestPSDTextAreaLabel.Visible='on';     

            app.opt_psd=best_psd;


        end

        % Value changed function: NumberofBreakpointsListBox
        function NumberofBreakpointsListBoxValueChanged(app, event)
            Number(app);
            clear(app);
        end

        % Value changed function: FreqConstraintsListBox
        function FreqConstraintsListBoxValueChanged(app, event)
            Number(app);
            clear(app);
        end

        % Value changed function: PSDTypeListBox
        function PSDTypeListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MaxSlopedBoctEditField
        function MaxSlopedBoctEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StartFreqHzEditField
        function StartFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: EndFreqHzEditField
        function EndFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                name=app.BestPSDOutputNameEditField.Value;
                assignin('base',name,app.opt_psd);
                msgbox('Array saved');
            catch
                warndlg('Save Failed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvelopePSDUIFigure = uifigure('Visible', 'off');
            app.EnvelopePSDUIFigure.Name = 'Envelope PSD';
            app.envelope_psd_aver10byTomIrvineLabel = uilabel(app.EnvelopePSDUIFigure);
            app.envelope_psd_aver10byTomIrvineLabel.Text = 'envelope_psd_a    ver 1.1   by Tom Irvine        This script calculates a simplified, optimized PSD to envelop an input PSD.  ';
            app.ReturnButton = uibutton(app.EnvelopePSDUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.EnvelopePSDUIFigure);
            app.InputPSDTab = uitab(app.TabGroup);
            app.InputPSDTab.Title = 'Input PSD';
            app.listbox_method = uilistbox(app.InputPSDTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputPSDTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputPSDTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputPSDTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputPSDTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NumberofBreakpointsListBoxLabel = uilabel(app.CalculateTab);
            app.NumberofBreakpointsListBoxLabel.Text = 'Number of Breakpoints';
            app.NumberofBreakpointsListBox = uilistbox(app.CalculateTab);
            app.NumberofBreakpointsListBox.Items = {'2 arbitrary', '3 arbitrary', '3 ramp-plateau', '4 arbitrary', '4 ramp-plateau-ramp', '5 arbitrary', '5 ramp-plateau-ramp-plateau', '6 Three Ramps & Two Plateaus', '8 Four Ramps & Three Plateaus', '3 plateau-ramp', '6 arbitrary', '7 arbitrary', '10 Five Ramps & Four Plateaus', '11 Six Ramps & Five Plateaus', '12 Seven Ramps & Six Plateaus'};
            app.NumberofBreakpointsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofBreakpointsListBoxValueChanged, true);
            app.NumberofBreakpointsListBox.Value = '2 arbitrary';
            app.PSDTypeListBoxLabel = uilabel(app.CalculateTab);
            app.PSDTypeListBoxLabel.Text = 'PSD Type';
            app.PSDTypeListBox = uilistbox(app.CalculateTab);
            app.PSDTypeListBox.Items = {'Accel (G^2/Hz)', 'Pressure (psi^2/Hz)'};
            app.PSDTypeListBox.ValueChangedFcn = createCallbackFcn(app, @PSDTypeListBoxValueChanged, true);
            app.PSDTypeListBox.Value = 'Accel (G^2/Hz)';
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofTrialsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanging, true);
            app.NumberofTrialsEditField.Value = '1000';
            app.StartFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.StartFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @StartFreqHzEditFieldValueChanging, true);
            app.StartFreqHzEditField.Value = '20';
            app.EndFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.EndFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @EndFreqHzEditFieldValueChanging, true);
            app.EndFreqHzEditField.Value = '2000';
            app.MaxSlopedBoctEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxSlopedBoctEditFieldLabel.Text = 'Max Slope (dB/oct)';
            app.MaxSlopedBoctEditField = uieditfield(app.CalculateTab, 'text');
            app.MaxSlopedBoctEditField.ValueChangingFcn = createCallbackFcn(app, @MaxSlopedBoctEditFieldValueChanging, true);
            app.MaxSlopedBoctEditField.Value = '21';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.CalculateTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.CalculateTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.FreqConstraintsListBoxLabel = uilabel(app.CalculateTab);
            app.FreqConstraintsListBoxLabel.Text = 'Freq Constraints';
            app.FreqConstraintsListBox = uilistbox(app.CalculateTab);
            app.FreqConstraintsListBox.Items = {'Yes', 'No'};
            app.FreqConstraintsListBox.ValueChangedFcn = createCallbackFcn(app, @FreqConstraintsListBoxValueChanged, true);
            app.FreqConstraintsListBox.Value = 'Yes';
            app.UITable = uitable(app.CalculateTab);
            app.UITable.ColumnName = {'n'; 'Lower Freq (Hz)'; 'Upper Freq (Hz)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.FreqLimitsbyBreakpointExcludingEndPointsLabel = uilabel(app.CalculateTab);
            app.FreqLimitsbyBreakpointExcludingEndPointsLabel.Text = {'Freq Limits by Breakpoint '; 'Excluding End Points'};
            app.RandomNumberSeedListBoxLabel = uilabel(app.CalculateTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.CalculateTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.ViewResultExportTab = uitab(app.TabGroup);
            app.ViewResultExportTab.Title = 'View Result & Export';
            app.BestPSDTextAreaLabel = uilabel(app.ViewResultExportTab);
            app.BestPSDTextAreaLabel.Text = 'Best PSD';
            app.BestPSDTextArea = uitextarea(app.ViewResultExportTab);
            app.BestPSDOutputNameEditFieldLabel = uilabel(app.ViewResultExportTab);
            app.BestPSDOutputNameEditFieldLabel.Text = 'Best PSD Output Name';
            app.BestPSDOutputNameEditField = uieditfield(app.ViewResultExportTab, 'text');
            app.SaveButton = uibutton(app.ViewResultExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.EnvelopePSDUIFigure);
            app.Label.Text = 'The script assumes an SDOF response.   The input array must be a PSD with two columns:  freq(Hz) & amp(unit^2/Hz)';
            % Show the figure after all components are created
            app.EnvelopePSDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = envelope_psd_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EnvelopePSDUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EnvelopePSDUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvelopePSDUIFigure)
        end
    end
end