classdef envelope_srs_data_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        envelopeSRSUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculateTab                   matlab.ui.container.Tab
        ReturnButton                   matlab.ui.control.Button
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        FormatListBox                  matlab.ui.control.ListBox
        FormatListBoxLabel             matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        CalculateButton                matlab.ui.control.Button
        NumberofTrialsEditField        matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel   matlab.ui.control.Label
        MargindBEditField              matlab.ui.control.EditField
        MargindBEditFieldLabel         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        envelope_srs_data_aver13byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        nspec % 
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
             for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function update(app)
            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);

            try
                FS=app.SelectInputArrayListBox.Value;
                THF=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            fig_num=1;

            Items = strtrim(app.FormatListBox.Items);
            im = strtrim(app.FormatListBox.Value);
            iform = find(strcmp(Items, im));
            
            if(iform==1)
                num=3;
            end
            if(iform==2)
                num=4;
            end
            if(iform==3)
                num=5;
            end
            if(iform==4)
                num=5;
            end
            if(iform==5)
                num=6;
            end
            if(iform==6)
                num=7;
            end
            if(iform==7)
                num=8;
            end

            dB=str2num(app.MargindBEditField.Value);
            
            mscale=10^(dB/20);
            
            THF=sortrows(THF,1);
            
            ntrials=str2num(app.NumberofTrialsEditField.Value);

            ioct=6;   % 1/96 octave interpolation
            
            fr=THF(:,1);
             r=THF(:,2);


            [f,srs_in]=SRS_specification_interpolation_nw(fr,r,ioct);
            
            omegan=2*pi*f;
            
            nf=length(f);
            
            fmin=f(1);
            fmax=f(nf);
            
            [spec]=srs_envelope_engine(ntrials,nf,f,srs_in,ioct,omegan,num,mscale,iform);
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            t_string='Shock Response Spectrum Q=10';
            
            ppp1=spec;
            ppp2=THF;
            
            leg1='envelope';
            leg2='measured data';
            
            md=5;

            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
    
            app.nspec=spec;

            fprintf('\n mscale = %6.3g \n',mscale);
 
            output_name=app.OutputArrayNameEditField.Value;
            assignin('base', output_name, app.nspec);

            msgbox(sprintf('SRS envelope saved as %s',output_name));

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.envelopeSRSUIFigure = uifigure('Visible', 'off');
            app.envelopeSRSUIFigure.Name = 'envelope SRS';
            app.envelope_srs_data_aver13byTomIrvineLabel = uilabel(app.envelopeSRSUIFigure);
            app.envelope_srs_data_aver13byTomIrvineLabel.Text = 'envelope_srs_data_a  ver 1.3  by Tom Irvine';
            app.Label = uilabel(app.envelopeSRSUIFigure);
            app.Label.Text = 'This script derives an SRS envelope for measured SRS data, minimizing total error.   The input array must have two columns:  Natural Frequency (Hz) & Peak Accel (G)';
            app.TabGroup = uitabgroup(app.envelopeSRSUIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.MargindBEditFieldLabel = uilabel(app.CalculateTab);
            app.MargindBEditFieldLabel.Text = 'Margin (dB)';
            app.MargindBEditField = uieditfield(app.CalculateTab, 'text');
            app.MargindBEditField.Value = '0';
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofTrialsEditField.Value = '3000';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.listbox_method = uilistbox(app.CalculateTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.CalculateTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculateTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculateTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.FormatListBoxLabel = uilabel(app.CalculateTab);
            app.FormatListBoxLabel.Text = 'Format';
            app.FormatListBox = uilistbox(app.CalculateTab);
            app.FormatListBox.Items = {'Ramp-Plateau', 'Ramp-Ramp-Plateau', 'Ramp-Plateau-Ramp-Plateau', 'Ramp-Ramp-Plateau-Ramp', 'Ramp-Ramp-Ramp-Plateau-Ramp', 'Ramp-Ramp-Ramp-Ramp-Plateau-Ramp', 'Arbitary 8'};
            app.FormatListBox.Value = 'Ramp-Plateau';
            app.OutputArrayNameEditFieldLabel = uilabel(app.CalculateTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.CalculateTab, 'text');
            app.OutputArrayNameEditField.Value = 'srs_spec';
            app.ReturnButton = uibutton(app.CalculateTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.envelopeSRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = envelope_srs_data_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.envelopeSRSUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.envelopeSRSUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.envelopeSRSUIFigure)
        end
    end
end