classdef export_junction_Apph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel_scale         matlab.ui.container.Panel
        edit_step             matlab.ui.control.EditField
        text_step             matlab.ui.control.Label
        text7                 matlab.ui.control.Label
        listbox_function_num  matlab.ui.control.ListBox
        listbox_precision     matlab.ui.control.ListBox
        text6                 matlab.ui.control.Label
        edit_scale            matlab.ui.control.EditField
        text_scale            matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        listbox_scale         matlab.ui.control.ListBox
        uipanel1              matlab.ui.container.Panel
        edit_matlab_name      matlab.ui.control.EditField
        pushbutton_return     matlab.ui.control.Button
        pushbutton_perform    matlab.ui.control.Button
        text2                 matlab.ui.control.Label
        uipanel2              matlab.ui.container.Panel
        listbox_format        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        n % Description
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.listbox_function_num.scroll(app.listbox_function_num.Items{7});
        end
        
        function format(app)

            Items = char(app.listbox_format.Items);
            im=app.listbox_format.Value;

            [app.n]=find_index(Items,im);
            
            if(app.n==3 || app.n==4 || app.n==5)
                app.uipanel_scale.Visible='on';
            else
                app.uipanel_scale.Visible='off';
            end
            
            if(app.n==3)
                app.text_step.Visible='off';
                app.edit_step.Visible='off';
            else
                app.text_step.Visible='on';
                app.edit_step.Visible='on';
            end
        end
        function scale(app)
            
            Items = char(app.listbox_format.Items);
            im=app.listbox_format.Value;

            [app.n]=find_index(Items,im);
            
            if(app.n==3 || app.n==4 || app.n==5)
                app.uipanel_scale.Visible='on';
            else
                app.uipanel_scale.Visible='off';
            end
            
            if(app.n==3)
                app.text_step.Visible='off';
                app.edit_step.Visible='off';
            else
                app.text_step.Visible='on';
                app.edit_step.Visible='on';
            end
        end


        function update(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    cell_array{i} = vars(i).name;
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function export_junction_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to export_junction (see VARARGIN)
            
            % Choose default command line output for export_junction
            handles.output = hObject;
            
            format(app);
            scale(app);
            updata(app);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_format
        function listbox_format_Callback(app, event)
            format(app);
        end

        % Value changed function: listbox_scale
        function listbox_scale_Callback(app, event)
            scale(app);
        end

        % Button pushed function: pushbutton_perform
        function pushbutton_perform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_perform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            disp('  ');
            disp(' * * * * * * ');
            disp('  ');

            try
                FS=get(handles.edit_matlab_name,'String');
                av=evalin('base',FS);
            catch
                warndlg('Matlab array does not exist');
                return
            end
            
            
            vname=@(x) inputname(1);
            sname=vname(av);
            
            
            if(app.n==1) % ascii
            
                [writefname, writepname] = uiputfile('*.txt','Save data as');
                writepfname = fullfile(writepname, writefname);

                writematrix(av,writepfname,'Delimiter','\t');
            
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.n==2)  % excel
            
                [writefname, writepname] = uiputfile('*.xls','Save model as Excel file');
                writepfname = fullfile(writepname, writefname);
            
                writematrix(av,writepfname);
            
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            %
            if(app.n==3 || app.n==4 || app.n==5) % nastran time history
            
                np=get(handles.listbox_precision,'Value');
            
                [writefname, writepname] = uiputfile('*','Save data as');
                writepfname = fullfile(writepname, writefname);
                fid = fopen(writepfname,'w');
            
                nscale=get(handles.listbox_scale,'Value');
            
                if(nscale==1)
                    try
                        scale=str2num(get(handles.edit_scale,'String'));
                    catch
                        warndlg(' Enter scale factor ');
                        return;
                    end
                end
            
                function_num=get(handles.listbox_function_num,'Value');
            
            end
            
            if(app.n==3) % nastran time history
            
                t=av(:,1);
                a=av(:,2);
            
                if(nscale==1)
                    a=a*scale;
                end
            
                sz=size(av);
                nn=sz(1);
            
                for i=1:nn
                    if(abs(a(i))<=0.0001 )
                        a(i)=0.;
                    end
                end
            
                dt=(t(nn)-t(1))/(nn-1);
            %
                for i=1:nn
                    t(i)=dt*(i-1);
                end
            %
            %  type=1  SOL SEMTRAN
            %      =2  SOL SEMODES
            %
            
                type=1;
            
                num=get(handles.listbox_function_num,'Value');
            
                if(np==1)
                    [~]=sp_nastran_export_num(nn,t,a,fid,num,type);
                else
                    [~]=dp_nastran_export_num(nn,t,a,fid,num,type);
                end
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            if(app.n==4 || app.n==5) % nastran response spectrum or PSD
            
                df=str2num(get(handles.edit_step,'String'));
            
                if(isempty(df))
                    warndlg(' Enter frequency step');
                    return;
                end
            
                f=av(:,1);
                a=av(:,2);
            
                if(nscale==1)
                    if(app.n==4)
                        a=a*scale;
                    else
                        a=a*scale^2;
                    end
                end
            
                sz=size(av);
                num=sz(1);
            
            %%
            
            %    m=floor((f(n)-f(1))/df);
            
            %    out1=sprintf('n=%d m=%d',n,m);
            %    disp(out1);
            
            
            %    for i=1:m
            
                i=0;
            
                while(1)
            
                     i=i+1;
            
                     new_freq=f(1)+df*(i-1);
            
                     if(new_freq>f(num))
                        break;
                     end
            
                     fff(i)=new_freq;


                     for j=1:(num-1)
                         if(fff(i)==f(j))
                             aaa(i)=a(j);
                             break;
                         end
                         if(fff(i)>f(j) && fff(i)<f(j+1))
                             slope=log(a(j+1)/a(j))/log(f(j+1)/f(j));
                             term=fff(i)/f(j);
                             aaa(i)=a(j)*term^slope;
                             break;
                         end
                         if( fff(i)==f(j+1))
                             aaa(i)=a(j+1);
                             break;
                         end
                     end
            
                end
            
                m=length(fff);
            
                if(fff(m)<f(num))
                     fff(m)=f(num);
                     aaa(m)=a(num);
                end
            
            %%
            
                clear a;
                a=aaa;
                clear f;
                f=fff;
                num=length(fff);
            
                for i=1:num
                    if(abs(a(i))<=0.0001 )
                        a(i)=0.;
                    end
                end
            
                type=2;
            
                length(a)
                length(f)
            %
            
                if(type==1)
                    if(np==1)
                        [~]=sp_nastran_export(num,t,a,fid);
                    else
                        [~]=dp_nastran_export(num,t,a,fid);
                    end
                end
                if(type==2)
                    if(np==1)
                        [~]=sp_nastran_export_num(num,f,a,fid,function_num,type);
                    else
                        [~]=dp_nastran_export_num(num,f,a,fid,function_num,type);
                    end
                end
            %


                fff=fix_size(fff);
                aaa=fix_size(aaa);
            
                if(app.n==4)
            
                    assignin('base', 'interpolated_srs',[fff aaa]);
            
                    disp(' ');
                    disp(' output array:  interpolated_srs ');
                    disp(' ');
            
                else
            
                    assignin('base', 'interpolated_psd',[fff aaa]);
            
                    disp(' ');
                    disp(' output array:  interpolated_psd ');
                    disp(' ');
            
                end
            
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.n==6) % mat
            
                [writefname, writepname] = uiputfile('*.mat','Save data as');
                
                pattern = '.mat';
                replacement = '';
                sname=regexprep(writefname,pattern,replacement);
                ss=sprintf('%s',sname);
                ss1=sprintf('%s',sname);
                  
                FS2=strtrim(get(handles.edit_matlab_name,'String'));        
                FS2=evalin('base',FS2);
                           
                out1=sprintf('%s=FS2;',ss);
                
                clear ss;
                
                eval(out1);
                
                elk=sprintf('%s%s',writepname,writefname);
                
                save(elk,ss1); 

            
            end
            %
            %
            msgbox('Export Complete.  Press Return. ');
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'export_junction';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Select Export Format';
            app.uipanel2.Tag = 'uipanel2';
            app.listbox_format = uilistbox(app.uipanel2);
            app.listbox_format.Items = {'ASCII Text', 'Excel               ', 'Nastran Time History', 'Nastran Response Spectrum, log-log', 'Nastran PSD, log-log', 'mat'};
            app.listbox_format.ValueChangedFcn = createCallbackFcn(app, @listbox_format_Callback, true);
            app.listbox_format.Tag = 'listbox_format';
            app.listbox_format.Value = 'ASCII Text';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'export_junction.m  ver 2.0 by Tom Irvine';
            app.pushbutton_perform = uibutton(app.figure1, 'push');
            app.pushbutton_perform.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_perform_Callback, true);
            app.pushbutton_perform.Tag = 'pushbutton_perform';
            app.pushbutton_perform.Text = 'Perform Export';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Existing Matlab Workspace Array Name';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_matlab_name = uieditfield(app.uipanel1, 'text');
            app.edit_matlab_name.Tag = 'edit_matlab_name';
            app.uipanel_scale = uipanel(app.figure1);
            app.uipanel_scale.Title = 'Panel';
            app.uipanel_scale.Tag = 'uipanel_scale';
            app.listbox_scale = uilistbox(app.uipanel_scale);
            app.listbox_scale.Items = {'yes', 'no'};
            app.listbox_scale.ValueChangedFcn = createCallbackFcn(app, @listbox_scale_Callback, true);
            app.listbox_scale.Tag = 'listbox_scale';
            app.listbox_scale.Value = 'yes';
            app.text4 = uilabel(app.uipanel_scale);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Apply Amplitude Scale Factor';
            app.text_scale = uilabel(app.uipanel_scale);
            app.text_scale.Tag = 'text_scale';
            app.text_scale.Text = 'Scale Factor';
            app.edit_scale = uieditfield(app.uipanel_scale, 'text');
            app.edit_scale.Tag = 'edit_scale';
            app.text6 = uilabel(app.uipanel_scale);
            app.text6.Tag = 'text6';
            app.text6.Text = {'Precision'; ''};
            app.listbox_precision = uilistbox(app.uipanel_scale);
            app.listbox_precision.Items = {'Single', 'Double'};
            app.listbox_precision.Tag = 'listbox_precision';
            app.listbox_precision.Value = 'Single';
            app.listbox_function_num = uilistbox(app.uipanel_scale);
            app.listbox_function_num.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.listbox_function_num.Tag = 'listbox_function_num';
            app.listbox_function_num.Value = '7';
            app.text7 = uilabel(app.uipanel_scale);
            app.text7.Tag = 'text7';
            app.text7.Text = {'Function Number'; '         '};
            app.text_step = uilabel(app.uipanel_scale);
            app.text_step.Tag = 'text_step';
            app.text_step.Text = {'Output Freq Step (Hz)'; '         '};
            app.edit_step = uieditfield(app.uipanel_scale, 'text');
            app.edit_step.Tag = 'edit_step';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_junction_Apph(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)export_junction_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end