classdef export_junction_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportUIFigure                  matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SelectArrayTab                  matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        WorkspaceListBox                matlab.ui.control.ListBox
        WorkspaceListBoxLabel           matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        ArrayNameEditField              matlab.ui.control.EditField
        ArrayNameEditFieldLabel         matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        uipanel_scale                   matlab.ui.container.Panel
        edit_step                       matlab.ui.control.EditField
        text_step                       matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        listbox_function_num            matlab.ui.control.ListBox
        listbox_precision               matlab.ui.control.ListBox
        text6                           matlab.ui.control.Label
        edit_scale                      matlab.ui.control.EditField
        text_scale                      matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        listbox_scale                   matlab.ui.control.ListBox
        SelectExportFormatListBox       matlab.ui.control.ListBox
        SelectExportFormatListBoxLabel  matlab.ui.control.Label
        text2                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        n % Description
        THM
    end
    
    methods (Access = private)

        %     value = app.listbox_scale.Value;
  

        function format(app)

            Items = strtrim(app.SelectExportFormatListBox.Items);
            im = strtrim(app.SelectExportFormatListBox.Value);
            app.n = find(strcmp(Items, im));

            
            if(app.n==3 || app.n==4 || app.n==5)
                app.uipanel_scale.Visible='on';
            else
                app.uipanel_scale.Visible='off';
            end
            
            if(app.n==3)
                app.text_step.Visible='off';
                app.edit_step.Visible='off';
            else
                app.text_step.Visible='on';
                app.edit_step.Visible='on';
            end
        end
        function scale(app)
            
            Items = strtrim(app.listbox_scale.Items);
            im = strtrim(app.listbox_scale.Value);
            app.n = find(strcmp(Items, im));

            if(app.n==3 || app.n==4 || app.n==5)
                app.uipanel_scale.Visible='on';
            else
                app.uipanel_scale.Visible='off';
            end
            
            if(app.n==3)
                app.text_step.Visible='off';
                app.edit_step.Visible='off';
            else
                app.text_step.Visible='on';
                app.edit_step.Visible='on';
            end
        end

        function update_list(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    cell_array{i} = vars(i).name;
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.WorkspaceListBox.Items=unique(sort(cell_array)); 
            % app.SelectInputArrayListBox.Items=app.WorkspaceListBox.Items;
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_list(app);
            format(app);
            scale(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update_list(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: SelectExportFormatListBox
        function SelectExportFormatListBoxValueChanged(app, event)
            
            format(app);
        end

        % Value changed function: listbox_scale
        function listbox_scaleValueChanged(app, event)
           
            scale(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
                
            disp('  ');
            disp(' * * * * * * ');
            disp('  ');

            try
                FS=app.WorkspaceListBox.Value;
                av=evalin('base',FS);
            catch
                warndlg('Matlab array does not exist');
                return
            end
            
            % vname=@(x) inputname(1);
            % sname=vname(av);
            
            if(app.n==1) % ascii
            
                [writefname, writepname] = uiputfile('*.txt','Save data as');
                writepfname = fullfile(writepname, writefname);
                writematrix(av,writepfname,'Delimiter','\t');
            
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.n==2)  % excel
            
                [writefname, writepname] = uiputfile('*.xlsx','Save model as Excel file');
                writepfname = fullfile(writepname, writefname);
            
                writematrix(av,writepfname);
            
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            if(app.n==3 || app.n==4 || app.n==5) % nastran time history

                Items = strtrim(app.listbox_precision.Items);
                im = strtrim(app.listbox_precision.Value);
                np = find(strcmp(Items, im));

                [writefname, writepname] = uiputfile('*','Save data as');
                writepfname = fullfile(writepname, writefname);
                fid = fopen(writepfname,'w');
  
                Items = strtrim(app.listbox_scale.Items);
                im = strtrim(app.listbox_scale.Value);
                nscale = find(strcmp(Items, im));
            
                if(nscale==1)
                    try
                        scale=str2num(app.edit_scale.Value);
                    catch
                        warndlg(' Enter scale factor ');
                        return;
                    end
                end
            
                function_num=str2num(app.listbox_function_num.Value);
            
            end
            
            if(app.n==3) % nastran time history
            
                t=av(:,1);
                a=av(:,2);
            
                if(nscale==1)
                    a=a*scale;
                end
            
                sz=size(av);
                nn=sz(1);
            
                for i=1:nn
                    if(abs(a(i))<=0.0001 )
                        a(i)=0.;
                    end
                end
            
                dt=min(diff(t));
            %
                for i=1:nn
                    t(i)=dt*(i-1);
                end
            %
            %  type=1  SOL SEMTRAN
            %      =2  SOL SEMODES
            %
            
                type=1;
            
                num=str2num(app.listbox_function_num.Value);
            
                if(np==1)
                    [~]=sp_nastran_export_num(nn,t,a,fid,num,type);
                else
                    [~]=dp_nastran_export_num(nn,t,a,fid,num,type);
                end
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            if(app.n==4 || app.n==5) % nastran response spectrum or PSD
            
                df=str2num(app.edit_step.Value);
            
                if(isempty(df))
                    warndlg(' Enter frequency step');
                    return;
                end
            
                f=av(:,1);
                a=av(:,2);
            
                if(nscale==1)
                    if(app.n==4)
                        a=a*scale;
                    else
                        a=a*scale^2;
                    end
                end
            
                sz=size(av);
                num=sz(1);
            
            %%
            
            %    m=floor((f(n)-f(1))/df);   
            %    out1=sprintf('n=%d m=%d',n,m);
            %    disp(out1);
            %    for i=1:m
            
                i=0;
            
                while(1)
            
                     i=i+1;
            
                     new_freq=f(1)+df*(i-1);
            
                     if(new_freq>f(num))
                        break;
                     end
            
                     fff(i)=new_freq;
            
                     for j=1:(num-1)
                         if(fff(i)==f(j))
                             aaa(i)=a(j);
                             break;
                         end
                         if(fff(i)>f(j) && fff(i)<f(j+1))
                             slope=log(a(j+1)/a(j))/log(f(j+1)/f(j));
                             term=fff(i)/f(j);
                             aaa(i)=a(j)*term^slope;
                             break;
                         end
                         if( fff(i)==f(j+1))
                             aaa(i)=a(j+1);
                             break;
                         end
                     end
            
                end
            
                m=length(fff);
            
                if(fff(m)<f(num))
                     fff(m)=f(num);
                     aaa(m)=a(num);
                end
            
            %%
            
                clear a;
                a=aaa;
                clear f;
                f=fff;
                num=length(fff);
            
                for i=1:num
                    if(abs(a(i))<=0.0001 )
                        a(i)=0.;
                    end
                end
            
                type=2;
            
                length(a)
                length(f)
            %
            
                if(type==1)
                    if(np==1)
                        [~]=sp_nastran_export(num,t,a,fid);
                    else
                        [~]=dp_nastran_export(num,t,a,fid);
                    end
                end
                if(type==2)
                    if(np==1)
                        [~]=sp_nastran_export_num(num,f,a,fid,function_num,type);
                    else
                        [~]=dp_nastran_export_num(num,f,a,fid,function_num,type);
                    end
                end
            %

                fff=fix_size(fff);
                aaa=fix_size(aaa);
            
                if(app.n==4)
            
                    assignin('base', 'interpolated_srs',[fff aaa]);
            
                    disp(' ');
                    disp(' output array:  interpolated_srs ');
                    disp(' ');
            
                else
            
                    assignin('base', 'interpolated_psd',[fff aaa]);
            
                    disp(' ');
                    disp(' output array:  interpolated_psd ');
                    disp(' ');
            
                end
            
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.n==6) % mat
            
                [writefname, writepname] = uiputfile('*.mat','Save data as');
                
                pattern = '.mat';
                replacement = '';
                sname=regexprep(writefname,pattern,replacement);
                ss=sprintf('%s',sname);
                ss1=sprintf('%s',sname);
                  
                FS2=evalin('base',FS); 
                out1=sprintf('%s=FS2;',ss);
                
                clear ss;
                
                eval(out1);
                
                elk=sprintf('%s%s',writepname,writefname);
                
                save(elk,ss1); 
            end
            %
            %
            msgbox('Export Complete.  Press Return. ');
        end

        % Button down function: ExportTab
        function ExportTabButtonDown(app, event)
            try
                FS=app.WorkspaceListBox.Value;
                app.ArrayNameEditField.Value=FS;
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportUIFigure = uifigure('Visible', 'off');
            app.ExportUIFigure.Name = 'Export ';
            app.text2 = uilabel(app.ExportUIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'export_junction_a  ver 2.2 by Tom Irvine';
            app.TabGroup = uitabgroup(app.ExportUIFigure);
            app.SelectArrayTab = uitab(app.TabGroup);
            app.SelectArrayTab.Title = 'Select Array';
            app.WorkspaceListBoxLabel = uilabel(app.SelectArrayTab);
            app.WorkspaceListBoxLabel.Text = 'Workspace';
            app.WorkspaceListBox = uilistbox(app.SelectArrayTab);
            app.WorkspaceListBox.Items = {''};
            app.WorkspaceListBox.Value = '';
            app.UpdateButton = uibutton(app.SelectArrayTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportTab.ButtonDownFcn = createCallbackFcn(app, @ExportTabButtonDown, true);
            app.SelectExportFormatListBoxLabel = uilabel(app.ExportTab);
            app.SelectExportFormatListBoxLabel.Text = 'Select Export Format';
            app.SelectExportFormatListBox = uilistbox(app.ExportTab);
            app.SelectExportFormatListBox.Items = {'ASCII Text', 'Excel               ', 'Nastran Time History', 'Nastran Response Spectrum, log-log', 'Nastran PSD, log-log', 'mat'};
            app.SelectExportFormatListBox.ValueChangedFcn = createCallbackFcn(app, @SelectExportFormatListBoxValueChanged, true);
            app.SelectExportFormatListBox.Value = 'ASCII Text';
            app.uipanel_scale = uipanel(app.ExportTab);
            app.uipanel_scale.Title = 'Panel';
            app.uipanel_scale.Tag = 'uipanel_scale';
            app.listbox_scale = uilistbox(app.uipanel_scale);
            app.listbox_scale.Items = {'yes', 'no'};
            app.listbox_scale.ValueChangedFcn = createCallbackFcn(app, @listbox_scaleValueChanged, true);
            app.listbox_scale.Tag = 'listbox_scale';
            app.listbox_scale.Value = 'yes';
            app.text4 = uilabel(app.uipanel_scale);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Apply Amplitude Scale Factor';
            app.text_scale = uilabel(app.uipanel_scale);
            app.text_scale.Tag = 'text_scale';
            app.text_scale.Text = 'Scale Factor';
            app.edit_scale = uieditfield(app.uipanel_scale, 'text');
            app.edit_scale.Tag = 'edit_scale';
            app.text6 = uilabel(app.uipanel_scale);
            app.text6.Tag = 'text6';
            app.text6.Text = {'Precision'; ''};
            app.listbox_precision = uilistbox(app.uipanel_scale);
            app.listbox_precision.Items = {'Single', 'Double'};
            app.listbox_precision.Tag = 'listbox_precision';
            app.listbox_precision.Value = 'Single';
            app.listbox_function_num = uilistbox(app.uipanel_scale);
            app.listbox_function_num.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.listbox_function_num.Tag = 'listbox_function_num';
            app.listbox_function_num.Value = '1';
            app.text7 = uilabel(app.uipanel_scale);
            app.text7.Tag = 'text7';
            app.text7.Text = {'Function Number'; '         '};
            app.text_step = uilabel(app.uipanel_scale);
            app.text_step.Tag = 'text_step';
            app.text_step.Text = {'Output Freq Step (Hz)'; '         '};
            app.edit_step = uieditfield(app.uipanel_scale, 'text');
            app.edit_step.Tag = 'edit_step';
            app.ExportButton = uibutton(app.ExportTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.ArrayNameEditFieldLabel.Text = 'Array Name';
            app.ArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.ArrayNameEditField.Editable = 'off';
            app.ReturnButton = uibutton(app.ExportUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.ExportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_junction_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExportUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ExportUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportUIFigure)
        end
    end
end