classdef filter_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterUIFigure                  matlab.ui.Figure
        AnalyzeButton                   matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        PerformAnalysisButton           matlab.ui.control.Button
        OutputSignalNameEditField       matlab.ui.control.EditField
        OutputSignalNameEditFieldLabel  matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        PlotInputButton                 matlab.ui.control.Button
        InputYaxisLabelEditField        matlab.ui.control.EditField
        InputYaxisLabelEditFieldLabel   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ReadDataTab                     matlab.ui.container.Tab
        UpdateListButton                matlab.ui.control.Button
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        SignalProcessingListBox         matlab.ui.control.ListBox
        SignalProcessingListBoxLabel    matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        aTab                            matlab.ui.container.Tab
        UpperFrequencyHzEditField       matlab.ui.control.EditField
        UpperFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LowerFrequencyHzEditField       matlab.ui.control.EditField
        LowerFrequencyHzEditFieldLabel  matlab.ui.control.Label
        TypeListBox                     matlab.ui.control.ListBox
        TypeLabel                       matlab.ui.control.Label
        RefilteringforPhaseCorrectionListBox  matlab.ui.control.ListBox
        RefilteringforPhaseCorrectionLabel  matlab.ui.control.Label
        OrderListBox                    matlab.ui.control.ListBox
        OrderListBoxLabel               matlab.ui.control.Label
        DisplayTransferFunctionButton   matlab.ui.control.Button
        ButterworthFilterLabel          matlab.ui.control.Label
        bTab                            matlab.ui.container.Tab
        DisplayTransferFunctionButton_2  matlab.ui.control.Button
        LowpassFreqHzEditField          matlab.ui.control.EditField
        LowpassFreqHzEditFieldLabel     matlab.ui.control.Label
        BesselTwoPoleFilterLabel        matlab.ui.control.Label
        cTab                            matlab.ui.container.Tab
        NumberofPassesListBox           matlab.ui.control.ListBox
        NumberofPassesLabel             matlab.ui.control.Label
        WindowSizeListBox_Mean          matlab.ui.control.ListBox
        WindowSizeListBox_2Label        matlab.ui.control.Label
        MeanFilterLabel                 matlab.ui.control.Label
        dTab                            matlab.ui.container.Tab
        WindowSizeListBox               matlab.ui.control.ListBox
        WindowSizeListBoxLabel          matlab.ui.control.Label
        MeidanFilterLabel               matlab.ui.control.Label
        eTab                            matlab.ui.container.Tab
        SetintegerharmonicsbasedonfirstfrequencyLabel  matlab.ui.control.Label
        SetHarmonicsButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NumberofTonesListBox            matlab.ui.control.ListBox
        NumberofTonesLabel              matlab.ui.control.Label
        TrialsperFrequencyEditField     matlab.ui.control.EditField
        TrialsperFrequencyEditFieldLabel  matlab.ui.control.Label
        RemoveSineTonesBWisBandwidthLabel  matlab.ui.control.Label
        fTab                            matlab.ui.container.Tab
        LowpassFreqHzEditField_2        matlab.ui.control.EditField
        LowpassFreqHzEditField_2Label   matlab.ui.control.Label
        LowpassFiltertoExtractRigidbodyAccelerationListBox  matlab.ui.control.ListBox
        LowpassFiltertoExtractRigidbodyAccelerationLabel  matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        DampingRatioLabel               matlab.ui.control.Label
        CrossOverFreqHzEditField        matlab.ui.control.EditField
        CrossOverFreqHzEditFieldLabel   matlab.ui.control.Label
        TrialsperFrequencyEditField_2   matlab.ui.control.EditField
        TrialsperFrequencyEditField_2Label  matlab.ui.control.Label
        NumberofDampedSinesEditField    matlab.ui.control.EditField
        NumberofDampedSinesEditFieldLabel  matlab.ui.control.Label
        ExtractTransientLabel           matlab.ui.control.Label
        gTab                            matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        TrialsperWaveletEditField       matlab.ui.control.EditField
        TrialsperWaveletEditFieldLabel  matlab.ui.control.Label
        NumberofWaveletsLabel           matlab.ui.control.Label
        MaxEditField_w                  matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_w                  matlab.ui.control.EditField
        MinEditField_3Label             matlab.ui.control.Label
        DelayTimesecEditField           matlab.ui.control.EditField
        DelayTimesecLabel               matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        NumberofFreqCasesListBox        matlab.ui.control.ListBox
        NumberofFreqCasesListBoxLabel   matlab.ui.control.Label
        WaveletFilteringLabel           matlab.ui.control.Label
        hTab                            matlab.ui.container.Tab
        FilterTypeListBox               matlab.ui.control.ListBox
        FilterTypeListBoxLabel          matlab.ui.control.Label
        ChannelFrequencyClassCFCLabel   matlab.ui.control.Label
        iTab                            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SVDTab                          matlab.ui.container.Tab
        MaximumNumberofPointstoIncludeEditField  matlab.ui.control.NumericEditField
        MaximumNumberofPointstoIncludeEditFieldLabel  matlab.ui.control.Label
        FilterTab                       matlab.ui.container.Tab
        SingularValueThresholdRatioEditField  matlab.ui.control.NumericEditField
        SingularValueThresholdRatioEditFieldLabel  matlab.ui.control.Label
        SVDFilterLabel                  matlab.ui.control.Label
        jTab                            matlab.ui.container.Tab
        EndTimesecEditField_2           matlab.ui.control.EditField
        EndTimesecEditField_2Label      matlab.ui.control.Label
        StartTimesecEditField_2         matlab.ui.control.EditField
        StartTimesecEditField_2Label    matlab.ui.control.Label
        DefineNonFilterTimeZoneListBox  matlab.ui.control.ListBox
        DefineNonFilterTimeZoneListBoxLabel  matlab.ui.control.Label
        SigmaThresholdEditField         matlab.ui.control.EditField
        SigmaThresholdLabel             matlab.ui.control.Label
        WindowSizesecEditField          matlab.ui.control.EditField
        WindowSizesecEditFieldLabel     matlab.ui.control.Label
        NumberofStagesListBox           matlab.ui.control.ListBox
        NumberofStagesLabel             matlab.ui.control.Label
        SigmaFiltertoRemoveNoiseSpikesLabel  matlab.ui.control.Label
        kTab                            matlab.ui.container.Tab
        PlotMaxFreqHzEditField          matlab.ui.control.NumericEditField
        PlotMaxFreqHzEditFieldLabel     matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        WindowListBoxLabel              matlab.ui.control.Label
        MeanRemovalListBox              matlab.ui.control.ListBox
        MeanRemovalListBoxLabel         matlab.ui.control.Label
        FFTLabel                        matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        filter_Appver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        listbox_choice             % Description
        n_Butterworth_type         %     
        n_Butterworth_order        %
        n_Butterworth_refilter     %
        order_label                %
        L_order                    %
        fc                         %
        fc2                        %
        THM                        %
        THM_orig                   %
        iflag                      %
        YS                         %
        n_DefineNonFilter          %
        n_tones                    %  
        tstart                     %
        tend                       % 
        n_EDS_filter               % 
        lpf_data                   %
        hpf_data                   %
        transient_th               %
        residual_th                %   
        amp_i                      %
        rigid_plus_transient_th    %
        rigid_plus_residual_th     %
        original_minus_rigid_th    % 
        nw_tones                   % 
        hw_choice                  %
        ttt                        %
        amp                        %
        fmax                       %
        magnitude_FT               %
        complex_FT                 %
        Gxx                        %                                                                                    
        num                        % 
        nhalf                      %  
        dt                         %
        df                         %
        freq                       %
        full                       %
        phase                      %
        magnitude_phase_FT         %
        fig_num                    %  
        q                          % 
        nnum                       %
        u                          %
        s                          %
        v                          %
    end
    
    methods (Access = private)

        function SVD_Filter(app)
            n=app.TabGroup2.SelectedTab;

            if(strcmp(n.Title,'SVD'))
                tic;

                app.ResultsTextArea.Value=" ";
                app.ResultsTextArea.Value='';        
                
                app.ResultsTextArea.Visible='on';
                app.ResultsTextAreaLabel.Visible='on';

                
                disp('  ');
                disp(' * * * * * * * ');
                disp('  ');
                
                app.q=app.THM(:,2);
                app.nnum=length(app.q);
                
                N=app.MaximumNumberofPointstoIncludeEditField.Value;
                
                if(N>6000)
                    warndlg('Maximum Number of Input Points reset to 6000');
                    app.MaximumNumberofPointstoIncludeEditField.Value=6000;
                    N=6000;
                end
                
                if(app.nnum>N)
                    app.THM=app.THM(1:N,:);
                    app.q=app.THM(:,2);   
                end
                   
                app.ResultsTextArea.Value=sprintf('Forming Hankel array');

                pause(2);

                H = hankel(app.q);
                string=sprintf('Hankel array formed \n  Calculating SVD...');
                app.ResultsTextArea.Value=string;
                
                [app.u,app.s,app.v] = svd(H);
                disp('SVD completed');
                
                app.nnum=length(app.q);
         
                
                sz=size(app.s);
                app.nnum=sz(1);
                
                s2=sprintf(' Singular Values');
                s3=sprintf(' i   \t    S  \t    Ratio ');

                ss=sprintf('%s \n\n %s \n',s2,s3);

                LL=min([12 app.nnum]);
                
                for i=1:LL
                    s4=sprintf(' %d \t %7.3e \t %7.3e ',i,app.s(i,i),app.s(i,i)/app.s(1,1));
                    ss=sprintf(' %s \n %s ',ss,s4);
                end
               
                toc            
                
                app.ResultsTextArea.Value=ss;               
                        
            else
                app.fig_num=1;
    
                ratio=app.SingularValueThresholdRatioEditField.Value;
                
                try
                    ss=app.s;
                    qx=app.q;
                catch
                    warndlg('Run SVD first');
                    return;              
                end   
                
                sz=size(ss);
                app.nnum=sz(1);

                if(app.num==0 )
                    warndlg('Run SVD first');
                    return;
                end
                
                th=ratio*ss(1,1);
                
                ijk=0;
                
                ds=zeros(app.nnum,1);
                
                ds(1)=ss(1,1);
                
                for i=2:app.nnum
                    
                    ds(i)=ss(i,i);
                    
                    if(ss(i,i)<th)
                        ss(i,i)=0;
                        ijk=ijk+1;
                    end
                end
                
                nz=app.nnum-ijk;
                
                fprintf('SVD nonzero values number %d \n',nz);
                
                disp('SVD truncation completed');
                
                B=app.u*ss*app.v';
                
                qq=zeros(app.nnum,1);
                
                qq(1)=B(1,1);
                
                for i=2:app.nnum
                    k=0;
                    for j=1:i-1
                        qq(i)=qq(i)+B(j,i-j);
                        k=k+1;
                    end
                    qq(i)=qq(i)/k;
                end
    
                disp('Filtering completed');

                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                Lq=length(qx);
                plot(app.THM(1:Lq,1),qx);
                xlabel('Time (sec)');
                ylabel(app.YS);
                title('Input Time History');
                grid on;
                
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                L=length(qq);            
                
                plot(app.THM(1:L,1),qq);
                xlabel('Time (sec)');  
                ylabel(app.YS);  
                title('Filtered Time History');
                grid on;
                
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                
                plot(app.THM(1:L,1),qx-qq);
                xlabel('Time (sec)');  
                ylabel(app.YS);  
                title('Residual Time History');
                grid on;            
                
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                plot(app.THM(1:L,1),qx,app.THM(1:L,1),qq);
                xlabel('Time (sec)');  
                ylabel(app.YS);  
                title('Time History');
                legend('Input','Filtered');
                grid on;

                Lqq=length(qq);
                
                filtered=[app.THM(1:Lqq,1),qq];
                
                try
                    name=app.OutputSignalNameEditField.Value;
                    assignin('base',name, filtered); 
                catch
                end
                
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                plot(ds);
                title('Singular Values prior to Truncation');
                grid on;

                ss=sprintf('Output array save as %s',name);
                msgbox(ss);
                
            end   

        end    

        function PreProcess_FFT(app)
            app.fmax=app.PlotMaxFreqHzEditField.Value;
            nmr=app.MeanRemovalListBox.Value;
            
            if(contains(nmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            nhw=app.WindowListBox.Value;
            
            if(contains(nhw,'Rectangular'))
                app.hw_choice=1;
            else
                app.hw_choice=2;
            end
            
            app.tend=app.THM(end,1);
            
            tdif=diff(app.THM(:,1));
            min_app.dt=min(tdif);
            max_app.dt=max(tdif);
            
            ratio=abs((max_app.dt-min_app.dt)/max_app.dt);
            
            if(ratio>0.05)
                fprintf('\n\n min_app.dt=%8.4g  max_app.dt=%8.4g \n\n',min_app.dt,max_app.dt);
                warndlg('Time step must be constant');
                return;
            end
            
            app.amp=app.THM(:,2);
            app.num=length(app.amp);
            app.num=2*floor(app.num/2);   % make even
            app.nhalf=floor(app.num/2);
            
            app.amp=app.amp(1:app.num);
            app.ttt=app.THM(1:app.num,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            fprintf('\n num=%d  app.dt=%8.4g  \n\n',app.num,app.dt);
            
            [app.amp]=mean_removal_Hanning(app.amp,mr_choice,app.hw_choice);            
        end    
        function PostProcess_FFT(app)

            disp(' f 1')

            if(app.hw_choice==1)
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.ttt,app.amp);
                title('Time History');
                ylabel(app.YS);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
            else
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                
                subplot(2,1,1);   
                plot(app.THM(:,1),app.THM(:,2));
                title('Filtered Time History');
                ylabel(app.YS);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
                
                subplot(2,1,2); 
                plot(app.ttt,app.amp);
                title('Filtered Time History with Hanning Window');
                ylabel(app.YS);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]); 
                grid on;    
            end
            
           disp(' f 2')

            if(app.fmax==0)
                sr=1/app.dt;
                app.fmax=sr/2;
            end

            disp(' f 3')
            
            [~,I]=max(app.magnitude_FT(:,2));
            fmaxp=app.magnitude_FT(I,1);
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.magnitude_FT(:,1),app.magnitude_FT(:,2));
            out1=sprintf('FFT Magnitude Filtered  Max Peak at %8.4g Hz',fmaxp);
            title(out1);
            ylabel(app.YS);
            xlabel('Frequency (Hz)');
            xlim([0 app.fmax]);
            grid on;     
           
            fff=app.magnitude_phase_FT(:,1);      
            nf=length(fff);
            
            app.complex_FT(:,2)=app.complex_FT(:,2)+(1i)*app.complex_FT(:,3);
            app.complex_FT(:,3)=[];
            
            app.Gxx=zeros(nf,1);
            app.Gxx(1)=abs(app.complex_FT(1,2));     
            
            for i=2:nf
                X=app.complex_FT(i,2);     
            	app.Gxx(i)=transpose(X)*X;
            end
            app.Gxx=[fff abs(app.Gxx)];                
            
        end

        function WaveletNumber(app)
             ClosePlots(app);
             NumSines(app);
        end
        function NumSines(app)
            app.nw_tones=str2num(app.NumberofFreqCasesListBox.Value);
            for i = 1:app.nw_tones
               for j=1:3
                  data_s{i,j} = '';     
               end    
            end
            app.UITable_2.Data=data_s;                         
            app.s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_2,app.s) % adjust the style
        end    
        function Wavelet_Filter(app)

            start_time=str2num(app.DelayTimesecEditField.Value); 

            N=app.nw_tones;
            A=char(app.UITable_2.Data);
            B=str2num(A);
                
            app.fc=B(1:N);
            sd=B((N+1):(2*N)); 
            nwave=B((2*N+1):(3*N)); 
                         
            minw=str2num(app.MinEditField_w.Value);
            maxw=str2num(app.MaxEditField_w.Value);

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [iunit]=find_index(Items,im);

            nt=str2num(app.TrialsperFrequencyEditField.Value);
             
            app.fig_num=1;
                
            Narrays=1;    
            
            for iv=1:Narrays
                
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.THM(:,1),app.THM(:,2));
                grid on;
                xlabel('Time (sec)');
             
                if(iunit<=2)
                    ylabel('Accel (G)');
                else
                    ylabel('Accel (m/sec^2)');    
                end
                title('Input Data');
             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
                dur=app.THM(end,1)-app.THM(1,1);
                app.dt=mean(diff(app.THM(:,1)));
                first=4/dur;
                tr=app.THM(:,1); 
                store_recon=app.THM(:,2);  
            
                [acceleration,velocity,displacement,wavelet_table]=...
                vibrationdata_wavelet_decomposition_engine_filter(tr,store_recon,app.dt,first,...
                                                    iunit,nt,start_time,minw,maxw,app.fc,sd,nwave);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                residual=app.THM;
                residual(:,2)=residual(:,2)-acceleration(:,2);
                rrr(:,1)=residual(:,1);
                rrr(:,2)=residual(:,2);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                sz1=size(app.THM);
                sz2=size(app.THM_orig);

                if(sz1(1)~=sz2(1))
                    figure(app.fig_num)
                    app.fig_num=app.fig_num+1;
                    plot(acceleration(:,1),acceleration(:,2),'r',app.THM_orig(:,1),app.THM_orig(:,2),'b');
                    ttts=sprintf('Input & Synth Accel %s',t_string);
                    title(ttts);
                    legend('Wavelet Series','Input Data');
                    grid on;
                    xlabel('Time(sec)');
                    if(iunit<=2)
                        ylabel('Accel(G)');
                    else
                        ylabel('Accel(m/sec^2)');         
                    end                   
                end    

                t_string='Wavelet Filtering';

                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                plot(acceleration(:,1),acceleration(:,2),'r',app.THM(:,1),app.THM(:,2),'b');
                ttts=sprintf('Input & Synth Accel %s',t_string);
                title(ttts);
                legend('Wavelet Series','Input Data');
                grid on;
                xlabel('Time(sec)');
                if(iunit<=2)
                    ylabel('Accel(G)');
                    ylabel1='Accel(G)';
                    ylabel2='Accel(G)';  
                    ylabel3='Accel(G)'; 
                else
                    ylabel('Accel(m/sec^2)'); 
                    ylabel1='Accel(m/sec^2)';
                    ylabel2='Accel(m/sec^2)';      
                    ylabel3='Accel(m/sec^2)';          
                end   
            
            xlabel2='Time (sec)';
            data1=app.THM;
            data2=acceleration;
            t_string1='Input';
            t_string2='Acceleration Synthesis';
            
            [app.fig_num]=subplots_two_linlin_two_titles_scale_same(app.fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            data3=rrr;
            t_string3='Residual';
            [app.fig_num]=subplots_three_linlin_three_titles_scale_same(app.fig_num,xlabel2,ylabel1,ylabel2,ylabel3,data1,data2,data3,t_string1,t_string2,t_string3);
            
            %%%%%%%%%%%%%%%
            
            tt=acceleration(:,1);
            a=acceleration(:,2);
            app.v=velocity(:,2);
            d=displacement(:,2);
            
            if(iunit<=2)
                ay='Accel(G)';
            else
                ay='Accel(m/sec^2)';    
            end   
            
            if(iunit==1)
                vy='Velocity(in/sec)';
            else
                vy='Velocity(cm/sec)'; 
            end
            
            if(iunit==1)
                dy='Disp(inch)';
            else
                dy='Disp(mm)';
            end
            
            aat=sprintf('Synthesized Acceleration ');
            vvt=sprintf('Synthesized Velocity ');
            dapp.dt=sprintf('Synthesized Displacement ');
            
            [app.fig_num,~]=plot_avd_time_histories_subplots_altp_titles_iu(app.fig_num,tt,a,v,d,ay,vy,dy,aat,vvt,dapp.dt,iunit);
            
            %%%%%%%%%%%%%%%
            
                figure(app.fig_num)
                app.fig_num=app.fig_num+1;
                plot(residual(:,1),residual(:,2));
                ttts=sprintf('Residual Accel %s',t_string);
                title(ttts);
                grid on;
                xlabel('Time(sec)');
                if(iunit<=2)
                    ylabel('Accel(G)');
                else
                    ylabel('Accel(m/sec^2)');    
                end   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
                prefix=app.OutputSignalNameEditField.Value;
            
                output_name_1=sprintf('%s_syn',prefix);
                output_name_2=sprintf('%s_res',prefix);
                output_name_3=sprintf('%s_table',prefix);    
            
                assignin('base', output_name_1, acceleration);
                assignin('base', output_name_2, residual);
                assignin('base', output_name_3, wavelet_table);

                sz=size(wavelet_table);
                n=sz(1);
                Amplitude=zeros(n,1);
                Freq_Hz=zeros(n,1);
                NHS=zeros(n,1);
                Delay_sec=zeros(n,1);

                for i=1:sz(1)
                    Amplitude(i)=str2num(sprintf('%7.3g',wavelet_table(i,3)));
                      Freq_Hz(i)=str2num(sprintf('%7.4g',wavelet_table(i,2)));
                          NHS(i)=wavelet_table(i,4);
                    Delay_sec(i)=str2num(sprintf('%7.3g',wavelet_table(i,5)));
                end    

                A = table(Freq_Hz,Amplitude,NHS,Delay_sec);
                A = sortrows(A,'Freq_Hz');
                
                output_name=app.OutputSignalNameEditField.Value;
            
                for ijk=1:1000
                    try
                        filename = sprintf('%s_%d.xlsx',output_name,ijk);
                        writetable(A,filename,'WriteMode','overwritesheet');
                        break;
                    catch
                    end
                end              

                try
                    pause(1);
                    winopen(filename);
                catch
                end             

                s1=sprintf(' ** Output data written to Excel file:  %s ** ',filename);

                s2=sprintf(' Acceleration: %s',output_name_1);
                s3=sprintf(' Residual: %s',output_name_2);
                s4=sprintf(' Wavelet Table: %s',output_name_3);

                assignin('base', output_name_1, acceleration);
                assignin('base', output_name_2, residual);
                assignin('base', output_name_3, wavelet_table);

                sss=sprintf('%s \n\n Matlab arrays \n\n %s \n %s \n %s',s1,s2,s3,s4);
                msgbox(sss);
                
            end
        end    

        function TimeChoice(app)
            n=app.SignalProcessingListBox.Value;
            if(contains(n,'Entire'))
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditField.Visible='off'; 
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';                 
            else    
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';              
            end
        end       
        
        function update(app)
            ClosePlots(app);
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end

        function ClosePlots(app)

            app.ResultsTextArea.Value='';
            app.ResultsTextArea.Visible='off';     
            app.ResultsTextAreaLabel.Visible='off';             

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end

        function PreProcess(app)

            app.YS=app.InputYaxisLabelEditField.Value;

            disp('ref 1')
        
            try
                ClosePlots(app);
            catch
                warndlg('ClosePlots failed');
                return;
            end

            disp('ref 2')

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp('ref 4')

            app.THM_orig=app.THM;  
                     
            ns=app.SignalProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end

            disp('ref 5')
            
            try
                [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            catch
                warndlg('check failed');
                return;
            end
            app.iflag
        end

        function AnalysisChange(app)
            ClosePlots(app);
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);
            try
                app.TabGroup.SelectedTab=app.TabGroup.Children(n);
            catch
            end
        end     

        function Mean_Filter(app)      
            
            y=double(app.THM(:,2));
            t=app.THM(:,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Input Time History');
            xlabel(' Time(sec) ')
            ylabel(app.YS)
            grid on;
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
            npass=str2num(app.NumberofPassesListBox.Value);
            windowSize=str2num(app.WindowSizeListBox_Mean.Value);
            
            %
            [mf]=mean_filter_function(y,npass,windowSize);   
            %
            yf=y-mf;
            %
            t=fix_size(t);
            yf=fix_size(yf);
            mf=fix_size(mf);
            %
            vvmax=max(yf);
            vvmin=min(yf);
            disp(' ');
            disp(' Input Data with Mean Filtered Signal Removed');          
            fprintf(' max=%8.4g  min=%8.4g \n',vvmax,vvmin);
            %
            figure(2);
            plot(t,yf);
            ylabel(app.YS)
            xlabel('Time (sec)')
            sss=sprintf('Input Data with Mean Filtered Signal Removed \n mf highpass');
            title(sss);
            grid on;
            raw_minus_mf(:,1)=t;
            raw_minus_mf(:,2)=yf;        
            %           
            figure(3);
            plot(t,mf);
            ylabel(app.YS)
            xlabel('Time (sec)')
            title(' Mean Filtered signal, mf lowpass');
            grid on;  
            mean_filtered(:,1)=t;
            mean_filtered(:,2)=mf;   
            %
            vvmax=max(mf);
            vvmin=min(mf);
            disp(' ');
            disp(' Mean Filtered Signal');          
            fprintf(' max=%8.4g  min=%8.4g \n',vvmax,vvmin);
            disp(' ');
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.fig_num=4;
            xlabel2='Time (sec)';
            ylabel1=app.YS;
            ylabel2=app.YS;
            data1=app.THM;
            data2=mean_filtered;
            
            ttts='Input Data';
            
            t_string1=ttts;
            t_string2='Mean Filtered';
            
            [app.fig_num]=subplots_two_linlin_two_titles_scale_same(app.fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            clear mf_lowpass;
            clear mf_highpass;
            %
            mf_lowpass=mean_filtered;
            mf_highpass=raw_minus_mf;
            %

            v1=[std(app.THM(:,2)),mean(app.THM(:,2))];
            v2=[std(mean_filtered(:,2)),mean(mean_filtered(:,2))];
            rms_input=norm(v1);
            rms_output=norm(v2);

            s1=sprintf(' Input');
            s2=sprintf('\n %8.4g RMS',rms_input);
            s3=sprintf('\n\n Mean Filtered Data');
            s4=sprintf('\n %8.4g RMS',rms_output);
            
            string=strcat(s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            name=app.OutputSignalNameEditField.Value;

            name_low=sprintf('%s_low',name);
            name_high=sprintf('%s_high',name);           
            
            assignin('base',name_low,mf_lowpass);
            assignin('base',name_high,mf_highpass);

            sss=sprintf('Lowpass Filtered data saved as: %s \n\n Highpass Filtered data saved as: %s',name_low,name_high);
            msgbox(sss);   

        end

        function Median_Filter(app)
            
            app.fig_num=2;

            a=app.THM(:,2);
            last=length(a);
            
            w=str2num(app.WindowSizeListBox.Value);
            %
            k=round((w-1)/2.);
            %
            mid=(w+1)/2;
            %
            signal_out=zeros(last,1);
            signal_out(:,2)=app.THM(:,2);
            %
            progressbar;
            
            for i=1:last
                progressbar(i/last);
            %
                c=zeros(w,1);
            %
	            jk=1;
            %
                for j=(i-k+1):(i+k+1)
            %	
                    if(j>=1 && j<=last )
			            c(jk)=a(j);
			            jk=jk+1;
                    end
            %        
                end
            %
                c=sort(c);
	            signal_out(i)=c(mid);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            end
            pause(0.3);
            progressbar(1);
            %
            signal_out(1)=a(1);
            
            signal_out=[app.THM(:,1) signal_out];
            
            xlabel2='Time (sec)';
            ylabel1=app.YS;
            ylabel2=app.YS;
            data1=app.THM;
            data2=signal_out;
            t_string1='Input Data';
            t_string2='Median Filtered';
            
            [app.fig_num]=subplots_two_linlin_two_titles_alt(app.fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),signal_out(:,1),signal_out(:,2));
            xlabel('Time (sec)');
            ylabel(app.YS);
            title('Time Histories');
            legend('Input','Filtered');
            grid on;
            
            v1=[std(app.THM(:,2)),mean(app.THM(:,2))];
            v2=[std(signal_out(:,2)),mean(signal_out(:,2))];
            rms_input=norm(v1);
            rms_output=norm(v2);

            s1=sprintf(' Input');
            s2=sprintf('\n %8.4g RMS',rms_input);
            s3=sprintf('\n\n Filtered Data');
            s4=sprintf('\n %8.4g RMS',rms_output);
            
            string=strcat(s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            name=app.OutputSignalNameEditField.Value;
            assignin('base',name,signal_out);
            sss=sprintf('Filtered data save as %s',name);
            msgbox(sss);     
            
        end    

        function Butterworth_type(app)

            ClosePlots(app);

            Items = char(app.TypeListBox.Items);
            im=app.TypeListBox.Value;
            [app.n_Butterworth_type]=find_index(Items,im);           

            if(app.n_Butterworth_type<=2)
                app.LowerFrequencyHzEditFieldLabel.Text='Frequency (Hz)';
                app.UpperFrequencyHzEditField.Visible='off';
                app.UpperFrequencyHzEditFieldLabel.Visible='off';
            else    
                app.LowerFrequencyHzEditFieldLabel.Text='Lower Frequency (Hz)';
                app.UpperFrequencyHzEditFieldLabel.Text='Upper Frequency (Hz)';             
                app.UpperFrequencyHzEditField.Visible='on';
                app.UpperFrequencyHzEditFieldLabel.Visible='on';            
            end          
        end    

        function Butterworth_preprocess(app)

            ClosePlots(app);

            Items = char(app.RefilteringforPhaseCorrectionListBox.Items);
            im=app.RefilteringforPhaseCorrectionListBox.Value;
            [app.n_Butterworth_refilter]=find_index(Items,im);          

            Items = char(app.OrderListBox.Items);
            im=app.OrderListBox.Value;
            [app.n_Butterworth_order]=find_index(Items,im);

            if(app.n_Butterworth_order==1)
               app.order_label='4th order'; 
               app.L_order=4;
            end   
            if(app.n_Butterworth_order==2)
               app.order_label='6th order';
               app.L_order=6;
            end
            if(app.n_Butterworth_order==3)
               app.order_label='8th order';
               app.L_order=8;
            end

            value = app.LowerFrequencyHzEditField.Value;
                
            if isempty(value)
                msgbox('Enter frequency');
                return;
            else   
                app.fc=str2num(value);        
            end   
       
            if(app.n_Butterworth_type>=3)

                value = app.UpperFrequencyHzEditField.Value;
                
                if isempty(value)
                    msgbox('Enter frequency');
                    return;
                else   
                    app.fc2=str2num(value);        
                end                  

            end        

        end    

        function Butterworth_Filter(app)

            disp('ref b1')

            Butterworth_preprocess(app);

            disp('ref b2')

            iphase=app.n_Butterworth_refilter;
            typ=app.n_Butterworth_type;
            iband=typ;

            if(app.iflag==999)
                return;
            end

            disp('ref b3')
            
            y=double(app.THM(:,2));
            
            rms_input=sqrt(mean(y)^2+std(y)^2);
            
            app.dt=mean(diff(app.THM(:,1)));
            
            clear filter_freq;
            
            if(iband<=2)
                filter_freq(1)=app.fc;
            end
            if(iband==3 || iband==4)
                freq1=app.fc;
                freq2=app.fc2; 
                filter_freq=[freq1; freq2];
            end

            disp('ref b4')
            
            [yf,~,~,~]=Butterworth_filter_function_order(y,app.dt,iband,filter_freq,iphase,app.L_order);
            
            y=fix_size(yf);
           
            disp('ref b5')

            filtered_data=[app.THM(:,1) y];
            
            rms_output=sqrt(mean(y)^2+std(y)^2);
           
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Input Time History');
            xlabel(' Time(sec) ');
            ylabel(app.YS);
            grid on;

            disp('ref 6')
            
            try
                figure(2);
                plot(filtered_data(:,1),filtered_data(:,2));
                if(iband==1)
                    out1=sprintf('Lowpass Filtered Time History %g Hz',filter_freq(1));
                end
                if(iband==2)
                    out1=sprintf('Highpass Filtered Time History %g Hz',filter_freq(1));    
                end
                if(iband==3)
                    out1=sprintf('Bandpass Filtered Time History %g to %g Hz',filter_freq(1),filter_freq(2));    
                end
                if(iband==4)
                    out1=sprintf('Bandstop Filtered Time History %g to %g Hz',filter_freq(1),filter_freq(2));    
                end
            
                out2=sprintf('Butterworth %s \n %s',app.order_label,out1);
                title(out2);
            
                xlabel(' Time(sec) ')
                ylabel(app.YS)
            
                try
                    yabs=max(abs(filtered_data(:,2)));
                    [~,yTT,ytt,app.iflag]=ytick_linear_double_sided(yabs);
            
                    if(app.iflag==1)
                        set(gca,'ytick',ytt);
                        set(gca,'YTickLabel',yTT);
                        ylim([min(ytt),max(ytt)]);
                    end
                catch
                end
            
                grid on;
            
            catch
                warndlg(' Filtered plot failed ');
                return;
            end
            
            s1=sprintf(' Input');
            s2=sprintf('\n %8.4g RMS',rms_input);
            s3=sprintf('\n\n Filtered Data');
            s4=sprintf('\n %8.4g RMS',rms_output);
            
            string=strcat(s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            name=app.OutputSignalNameEditField.Value;
            assignin('base',name,filtered_data);
            sss=sprintf('Filtered data save as %s',name);
            msgbox(sss);       

        end    
        function Butterworth_filter_transfer(app)

            Butterworth_preprocess(app);
            
            ire=app.n_Butterworth_refilter;
            typ=app.n_Butterworth_type;
            
            if(typ<=2)
                lpf=app.fc;
                hpf=app.fc;
            end

            if(typ==3) % bandpass
                hpf=min([app.fc app.fc2 ]);            
                lpf=max([app.fc app.fc2 ]);
                app.fc=lpf;
            end
            if(typ==4) % bandstop
                lpf=min([app.fc app.fc2 ]);            
                hpf=max([app.fc app.fc2 ]);
                app.fc=lpf;
            end
            %
            L=app.L_order;
            [sc]=Butterworth_order(app.L_order);
            %
            nn=5000;
            fmin=min([app.fc app.fc2 ]);
            app.fmax=max([app.fc app.fc2 ]);
            app.df=fmin/100;
            %
            H=zeros(nn,1);
            fH=zeros(nn,1);
            %
            for i=1:nn
                ff=i*app.df;
                app.s=complex(0,(ff/app.fc));
            %
                if(typ==2) % highpass
                    app.s=1/app.s;
                end
            %
                A=1;
                
                for k=1:round(L/2)
                    A=A*(app.s-sc(k))*(app.s-sc(L+1-k));
                end
            %    
                H(i)=1/A;
                fH(i)=ff;
            %
                if(ire==1)  % refiltering 
                    H(i)=H(i)*conj(H(i));
                end
            %
            end
            %

            if(typ==3 || typ==4)  % bandpass or bandstop
                G1=H;
                clear H;
            %
                 H=zeros(nn,1);  
                G2=zeros(nn,1);
            %

                for i=1:nn 
                    ff=i*app.df;       
                    app.s=complex(0,(ff/hpf));
                    app.s=1/app.s;
            %        
                    A=1;    
                    for k=1:round(L/2)
                        A=A*(app.s-sc(k))*(app.s-sc(L+1-k));
                    end
            %    
                    H(i)=1/A;
             
                    if(ire==1)  % refiltering 
                        H(i)=H(i)*conj(H(i));
                    end
                    G2(i)=H(i);
                    
                    if(typ==3)   % bandpass
                        H(i)=G2(i)'*G1(i);
                    else         % bandstop
                        H(i)=G2(i)+G1(i);           
                    end
                end
            end    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            % fH=fH*fc*tpi;
            %
            if(ire==1)
                if(typ==1)
                    out1=sprintf(' Butterworth Lowpass Filter %s Refiltering fc=%g Hz ',app.order_label,lpf);
                end
                if(typ==2)
                    out1=sprintf(' Butterworth Highpass Filter %s Refiltering fc=%g Hz ',app.order_label,hpf);
                end
                if(typ==3)
                    out1=sprintf(' Butterworth Bandpass Filter %s Refiltering %g to %g Hz ',app.order_label,hpf,lpf);
                end
                if(typ==4)
                    out1=sprintf(' Butterworth Bandstop Filter %s Refiltering %g to %g Hz ',app.order_label,lpf,hpf);
                end    
            else
                if(typ==1)
                    out1=sprintf(' Butterworth Lowpass Filter %s fc=%g Hz ',app.order_label,lpf);
                end
                if(typ==2)
                    out1=sprintf(' Butterworth Highpass Filter %s fc=%g Hz ',app.order_label,hpf);
                end
                if(typ==3)
                    out1=sprintf(' Butterworth Bandpass Filter %s %g to %g Hz ',app.order_label,hpf,lpf);
                end    
                if(typ==4)
                    out1=sprintf(' Butterworth Bandstop Filter %s %g to %g Hz ',app.order_label,lpf,hpf);
                end       
            end
            
            disp(' ');
            disp(out1);
            disp(' ');
            
            %
            xmax=10^(ceil(0.01+log10(app.fmax)));
            xmin=10^(floor(-0.01+log10(fmin)));  
            %
            %  Phase
            %
            figure(3);
            subplot(3,1,1);
            
            theta=angle(H)*180/pi;
            
            plot(fH,theta);
            ylim([-180,180]);
            xlim([ xmin xmax ]);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log',...
                                'YScale','lin','ytick',[-180,-90,0,90,180]);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
            ylabel(' Phase(deg) ');
            xlabel(' Frequency (Hz) ');
            title(out1);
            grid on;
            %
            %  Magnitude
            %
            subplot(3,1,[2 3]);
            plot(fH,abs(H));
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log');
            grid on;
            xlabel(' Frequency (Hz) ');
            ylabel(' Magnitude ');
            ymax=1;
            ymin=ymax/100;
            ylim([ ymin ymax ]);
            xlim([ xmin xmax ]);
            %
            figure(4);
            plot(fH,real(H),fH,imag(H));
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
            ylabel(' Amplitude ');
            xlabel(' Frequency (Hz) ');
            title(out1);
            legend ('real','imaginary');  
            grid on;

        end   

        function Bessel_Filter(app)

            app.v=app.LowpassFreqHzEditField.Value;

            if(isempty(app.v))
                warndlg('Enter Frequency')
                return;
            end    

            app.fc=str2num(app.v);
            y=double(app.THM(:,2));
            t=app.THM(:,1);
            
            rms_input=sqrt(mean(y)^2+std(y)^2);
            
            n=length(y);
            
            dur=app.THM(n,1)-app.THM(1,1);
            app.dt=dur/(n-1);
 
            [yf]=bessel_filter_core(app.fc,app.dt,y);
            
            t=fix_size(t);
            filtered_data=[t yf];
            %
            figure(1);
            plot(t,app.THM(:,2));
            title('Input Time History');
            xlabel(' Time(sec) ')
            ylabel(app.YS)
            grid on;
            
            figure(2);
            plot(t,yf);
            out1=sprintf('Bessel Lowpass Filtered Time History %g Hz',app.fc);
            title(out1);
            xlabel(' Time(sec) ')
            ylabel(app.YS)
            grid on;
                        
            res=app.THM(:,2)-yf;
            residual_data=[t res];
            
            figure(3);
            plot(t,res);
            out1=sprintf('Residual Time History %g Hz',app.fc);
            title(out1);
            xlabel(' Time(sec) ')
            ylabel(app.YS)
            grid on;
            
            rms_output=sqrt(mean(yf)^2+std(yf)^2);
            rms_residual=sqrt(mean(res)^2+std(res)^2);
            
            setappdata(0,'filtered_data',filtered_data);   
            setappdata(0,'residual_data',residual_data);  
            
            s1=sprintf(' Input');
            s2=sprintf('\n %9.5g RMS',rms_input);
            s3=sprintf('\n\n Filtered Data');
            s4=sprintf('\n %9.5g RMS',rms_output);
            s5=sprintf('\n\n Residual Data');
            s6=sprintf('\n %9.5g RMS',rms_residual);
            
            string=strcat(s1,s2,s3,s4,s5,s6);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            name=app.OutputSignalNameEditField.Value;

            name_low=sprintf('%s_low',name);
            name_high=sprintf('%s_high',name);           
            
            assignin('base',name_low,[t yf]);
            assignin('base',name_high,[t res]);

            sss=sprintf('Lowpass Filtered data saved as: %s \n\n Highpass Filtered data saved as: %s',name_low,name_high);
            msgbox(sss);   

        end    
        function Bessel_Filter_Transfer(app)

            app.fig_num=1;

            app.v=app.LowpassFreqHzEditField.Value;

            if(isempty(app.v))
                warndlg('Enter Frequency')
                return;
            end    

            app.fc=str2num(app.v);

            L=2;  % two-poles, second order
            
            dzero=(factorial(2*L))/((2^L)*factorial(L));
            %
            nn=5000;
            app.df=app.fc/100;
            %
            fH=zeros(nn,1);
            H=zeros(nn,1);
            B=zeros(L+1,1);
            %
            for i=1:nn
                ff=i*app.df;
                fH(i)=ff;
                app.s=complex(0,(ff/app.fc));
                B(1)=1;
                B(2)=app.s+1;
                for j=3:L+1
                    B(j)=(2*L-1)*B(j-1) + app.s^2*B(j-2);
                end
                H(i)=dzero/B(L+1);
            %  
            end
            %
            lpf=app.fc;
            out1=sprintf(' Bessel Lowpass Filter order=%d fc=%g Hz ',L,lpf);
            %
            %  Phase
            %
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            
            subplot(3,1,1);
            
            theta=angle(H)*180/pi;
            
            plot(fH,theta);
            ylim([-180,0]);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log',...
                                'YScale','lin','ytick',[-180,-90,0]);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin')
            ylabel(' Phase(deg) ');
            xlabel(' Frequency (Hz) ');
            title(out1);
            grid on;
            %
            %  Magnitude
            %
            subplot(3,1,[2 3]);
            plot(fH,abs(H));
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            grid on;
            xlabel(' Frequency (Hz) ');
            ylabel(' Magnitude ');
            
            %
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            
            plot(fH,real(H),fH,imag(H));
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin')
            ylabel(' Amplitude ');
            xlabel(' Frequency (Hz) ');
            title(out1);
            legend ('real','imaginary');  
            grid on;           
        end

        function DefineNonFilter(app)
            app.n_DefineNonFilter=app.DefineNonFilterTimeZoneListBox.Value;
            if(strcmp(app.n_DefineNonFilter,'Yes'))
                app.StartTimesecEditField_2.Visible='on';
                app.StartTimesecEditField_2Label.Visible='on';
                app.EndTimesecEditField_2.Visible='on';
                app.EndTimesecEditField_2Label.Visible='on';         
            else
                app.StartTimesecEditField_2.Visible='off';
                app.StartTimesecEditField_2Label.Visible='off';  
                app.EndTimesecEditField_2.Visible='off';
                app.EndTimesecEditField_2Label.Visible='off';                 
            end    
        end    

        function Sigma_Filter(app)
     
            app.fig_num=2;

            t=app.THM(:,1);
            a=app.THM(:,2);

            nstages=str2num(app.NumberofStagesListBox.Value);
            DefineNonFilter(app);

            if(strcmp(app.n_DefineNonFilter,'Yes'))
                tt1=str2num(app.StartTimesecEditField_2.Value);
                tt2=str2num(app.EndTimesecEditField_2.Value);

                [~,iit]=min(abs(t-tt1));
                [~,jjt]=min(abs(t-tt2));  
            end

            app.dt=mean(diff(t));

            ws=str2num(app.WindowSizesecEditField.Value);
            sigma=str2num(app.SigmaThresholdEditField.Value);

            app.num=round(ws/app.dt);
            dur=t(end)-t(1);
            ns=ceil(dur/ws);

            i1=1;

            aa=a;
            L=length(aa);

            fprintf('\n ws=%d  ns=%d  nstages=%d \n\n',ns,ws,nstages);

            for i=1:ns

                try
                    i2=i1+app.num;
                    if(i2>L)
                        i2=L;
                    end    
    
                    vv=aa(i1:i2);
    
                    for k=1:nstages
                        [~, minI] = min(vv);
                        [~, maxI] = max(vv);
        
                        Imin=min([minI maxI]);
                        Imax=max([minI maxI]);
        
                        vv(Imax)=[];
                        vv(Imin)=[];
                    end
     
                    sstd=std(vv);
                    amean=mean(vv);
    
                    for j=i1:i2
                 
                        if(abs(aa(j)/sstd)>sigma)
                            if(strcmp(app.n_DefineNonFilter,'Yes'))
                                if(j<iit || j>jjt)
                                    aa(j)=amean;
                                end    
                            else
                                aa(j)=amean;
                            end
                        end
               
                    end
    
                    i1=i2+1;

                catch
                end
            end

            xlabel2='Time (sec)';
            ylabel1=app.YS;
            ylabel2=app.YS;

            data1=[t a];
            data2=[t aa];

            t_string1='Input';
            t_string2='Filtered';

            [app.fig_num]=subplots_two_linlin_two_titles_scale_same(app.fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);

            output_name=app.OutputSignalNameEditField.Value;
            assignin('base',output_name, data2);

            v1=[std(data1(:,2)),mean(data1(:,2))];
            v2=[std(data2(:,2)),mean(data2(:,2))];
            rms_input=norm(v1);
            rms_output=norm(v2);

            s1=sprintf(' Input');
            s2=sprintf('\n %8.4g RMS',rms_input);
            s3=sprintf('\n\n Filtered Data');
            s4=sprintf('\n %8.4g RMS',rms_output);
            
            string=strcat(s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            sss=sprintf('Filtered data save as %s',output_name);
            msgbox(sss);             

        end    

        function CFC_Filter(app)
            
            y=double(app.THM(:,2));
            rms_input=sqrt(mean(y)^2+std(y)^2);
            
            app.dt=mean(diff(app.THM(:,1)));
            sr=1/app.dt;
            
            Items = char(app.FilterTypeListBox.Items);
            im=app.FilterTypeListBox.Value;
            [m]=find_index(Items,im);
                        
            if(m==1)
                cfc=60;
                sr_min=600;
                sstt='CFC 60';
            end
            if(m==2)
                cfc=180;
                sr_min=1800; 
                sstt='CFC 300';    
            end
            if(m==3)
                cfc=600;
                sr_min=6000;
                sstt='CFC 600';    
            end
            if(m==4)
                cfc=1000;
                sr_min=10000;
                sstt='CFC 1000';    
            end
            
            if(sr<sr_min)
                out1=sprintf(' SR=%8.4g Hz,  %s Minimum SR=%8.4g ',sr,sstt,sr_min);
                warndlg(out1);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            T=app.dt;
            omega_d=2*pi*cfc*2.0775;
            arg=(omega_d*T/2);
            omega_a=sin(arg)/cos(arg);
            
            den= 1 +sqrt(2)*omega_a + omega_a^2;
            
            a0=omega_a^2/den;
            a1=2*a0;
            a2=a0;
            
            b1=  -2*(omega_a^2-1)/den;
            b2=  (-1 +sqrt(2)*omega_a - omega_a^2)/den;
            
            forward=[ a0 a1 a2 ];
            back   =[  1 -b1 -b2 ];
            
            y=filter(forward,back,y);
            y = flipud( y );
            
            y=filter(forward,back,y);
            y = flipud( y );
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            y=fix_size(y);
            filtered_data=[app.THM(:,1) y];
            
            rms_output=sqrt(mean(y)^2+std(y)^2);
            
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Input Time History');
            xlabel(' Time(sec) ');
            ylabel(app.YS);
            grid on;
            
            figure(2);
            plot(filtered_data(:,1),filtered_data(:,2));
            
            out1=sprintf('%s Low Pass Filter',sstt);
            title(out1);
            
            xlabel(' Time(sec) ')
            ylabel(app.YS)
            grid on;
                
            s1=sprintf(' Input');
            s2=sprintf('\n %8.4g RMS',rms_input);
            s3=sprintf('\n\n Filtered Data');
            s4=sprintf('\n %8.4g RMS',rms_output);
            
            string=strcat(s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            output_name=app.OutputSignalNameEditField.Value;
            assignin('base',output_name,filtered_data);         

            sss=sprintf('Filtered data save as %s',output_name);
            msgbox(sss);    

        end    

        function SineTones(app)

            app.n_tones=str2num(app.NumberofTonesListBox.Value);
            for i = 1:app.n_tones
               for j=1:3
                  data_s{i,j} = '';     
               end    
            end
            app.UITable.Data=data_s;                         
            app.s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,app.s) % adjust the style

        end    

        function Remove_Sine_Tones(app) 

            app.n_tones=str2num(app.NumberofTonesListBox.Value);
            N=app.n_tones;
            A=char(app.UITable.Data);
            B=str2num(A);
                
            app.freq=B(1:N);
            bw=B((N+1):(2*N)); 
            npf=B((2*N+1):(3*N)); 
                          
            nfr=sum(npf);

            fprintf('\n nfr=%d \n',nfr);
            
            fl=zeros(nfr,1);
            fu=zeros(nfr,1);
            
            k=1;
            for i=1:N
               for j=1:npf(i)
                   fl(k)=app.freq(i)-bw(i)/2;
                   fu(k)=app.freq(i)+bw(i)/2;
                   k=k+1;
               end 
            end   
            
            tim=app.THM(:,1);
            app.amp=app.THM(:,2);

            t=tim;
            a=app.amp;
             
            n=length(app.amp);
            dur=app.THM(n,1)-app.THM(1,1);
            app.dt=dur/(n-1);
            
            app.amp=app.THM(:,2);
            tim=app.THM(:,1);
            
            tmi=tim(1,1);
            tmx=tim(end,1);

            dur=tim(end)-tim(1);
            
            if(dur<=1.0e-20)
                warndlg(' Duration = 0 ');
                return;
            end
            
            [syn,residual,Ar,Br,omeganr]=sine_find_function_manual(dur,a,app.amp,tim,app.dt,nfr,fl,fu);
                        
            running_sum=syn;
            
            x1r=zeros(nfr,1);
            x3r=zeros(nfr,1);    
             
            x2r=omeganr;
            
            for ie=1:nfr    
                x1r(ie)=norm([Ar(ie) Br(ie)]);
                x3r(ie)=atan2(Ar(ie),Br(ie));    
            end
            
            disp(' ');
            disp(' Results');
            disp(' ');
            disp(' Case   Amplitude   fn(Hz)   Phase(rad)  ');
            %
            tp=2*pi;
            x2r_d_tp=zeros(nfr,1);
            %
            for ie=1:nfr
                fprintf('  %d  %10.4f  %10.4f  %10.4f  \n',ie,x1r(ie),x2r(ie)/tp,x3r(ie));
                x2r_d_tp(ie)=x2r(ie)/tp;

                x1r(ie)=str2num(sprintf('%7.3g',x1r(ie)));
                x2r_d_tp(ie)=str2num(sprintf('%7.4g',x2r_d_tp(ie)));               
                x3r(ie)=str2num(sprintf('%7.3g',x3r(ie)));

            end 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Case = 1:nfr;
            Amplitude = x1r;
            fn_Hz = x2r_d_tp;
            Phase_rad=x3r;

            Case=fix_size(Case);
            Amplitude=fix_size(Amplitude);
            fn_Hz=fix_size(fn_Hz);
            Phase_rad=fix_size(Phase_rad);

            A = table(Case,Amplitude,fn_Hz,Phase_rad);
            
            output_name=app.OutputSignalNameEditField.Value;
            
            for ijk=1:1000
                try
                    filename = sprintf('%s_%d.xlsx',output_name,ijk);
                    writetable(A,filename,'WriteMode','overwritesheet');
                    break;
                catch
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(2)
            plot(t,residual);
            title('Residual');
            xlabel('Time (sec)');
            ylabel(app.YS);
            grid on;
            %
            figure(3)
            plot(t,running_sum);
            title('Synthesis');
            xlabel('Time (sec)');
            ylabel(app.YS);          
            grid on;
            %
            figure(4)
            plot(tim,app.amp,'b',t,running_sum,'r');
            %
            % plot(t,syn,'-.');
            %
            xlabel(' Time (sec) ');
            ylabel(app.YS);
            legend ('Input Data','Synthesis');
            tmin=tmi;
            tmax=tmx;
            %
            ymax=1.5*max(app.amp);
            ymin=1.5*min(app.amp);
            axis([tmin,tmax,ymin,ymax]);
            zoom on;
            
            t=fix_size(t);
            residual=fix_size(residual);
            
            running_sum=fix_size(running_sum);
            
            synth_th=[t running_sum ];
            app.residual_th=[t residual];

            name_synth=sprintf('%s_synth',output_name);
            name_residual=sprintf('%s_residual',output_name);
            
            assignin('base',name_synth,synth_th);
            assignin('base',name_residual,app.residual_th);

            s1=' Standard Deviations ';
            s2=sprintf(' original: %8.4g ',std(app.THM(:,2)));
            s3=sprintf(' synthesis: %8.4g ',std(running_sum));
            s4=sprintf('  residual: %8.4g ',std(residual));
            
            string=sprintf('%s \n\n %s \n %s \n %s \n',s1,s2,s3,s4);
            
            app.ResultsTextArea.Value=string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';          

            try
                pause(1);
                winopen(filename);
            catch
            end    

            s1=sprintf(' ** Output data written to Excel file:  %s ** \n\n',filename);
            s2=sprintf('Synthesis data saved as: %s \n\n Residual data saved as: %s',name_synth,name_residual);
            sss=sprintf('%s %s',s1,s2);
            msgbox(sss);   

        end

        function EDS_filter(app)
            Items = char(app.LowpassFiltertoExtractRigidbodyAccelerationListBox.Items);
            im=app.LowpassFiltertoExtractRigidbodyAccelerationListBox.Value;
            [app.n_EDS_filter]=find_index(Items,im);

            if(app.n_EDS_filter==1)
                app.LowpassFreqHzEditField_2.Visible='on';
                app.LowpassFreqHzEditField_2Label.Visible='on';
            else
                app.LowpassFreqHzEditField_2.Visible='off';
                app.LowpassFreqHzEditField_2Label.Visible='off';            
            end    
        end    

        function Extract_Transient(app)
            if(app.n_EDS_filter==1)
                et_mean_lpf(app);
            end
            sinefdam(app);
        end    

        function sinefdam(app)
            tic

            ClosePlots(app);
            
            app.fig_num=1;
            
            tp=2*pi;
            
            nfr=str2num(app.NumberofDampedSinesEditField.Value);
            nt=str2num(app.TrialsperFrequencyEditField_2.Value);
            
            md1=str2num(app.MinEditField_2.Value);
            md2=str2num(app.MaxEditField.Value);
            
            max_damp=max([md1 md2]);
            min_damp=min([md1 md2]);
            
            app.amp=app.THM(:,2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            s_tim=app.THM(:,1);
            s_amp=app.THM(:,2);
            
            n = length(s_tim); 
            %
            tmx=s_tim(1);
            tmi=s_tim(end);
            %
            fprintf('\n  %d samples \n\n',n);
            %
            app.dt=mean(diff(s_tim));
            sr=1/app.dt;
            %
            fprintf(' SR  = %g samples/sec    app.dt = %g sec \n\n',sr,app.dt);
            fprintf(' start  = %g sec    end = %g sec            \n\n',tmi,tmx); 
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            nf=app.n_EDS_filter;
            
            if(nf==1)
                
                app.amp=app.amp-app.lpf_data(:,2);  % subtract rigid body
                app.amp=fix_size(app.amp);
                s_amp=app.amp;  % leave here
                
                app.original_minus_rigid_th=[app.THM(:,1) app.amp];
                app.amp_i=[app.THM(:,1) app.amp];
                
                et_mean_hpf(app);
                
                app.amp=app.amp-app.hpf_data(:,2);
                hpf_data_sh=app.hpf_data(:,2);
            
                a=app.amp; 
            else
                a=app.amp;
                a=fix_size(a);
            end
            
            av=a;
            amp_orig=a;
            
            tim=s_tim';
            t=tim;
            %
            np=length(tim);
            num2=np;
            %
            fprintf(' np  = %d  \n\n',np);
            %
            dur=t(num2)-t(1);
            %    
            md1=max_damp;
            md2=min_damp;
            
            [syn,residual,Ar,Br,omeganr,dampr,delayr]=...
                damped_sine_find_function_delay_nt(dur,av,amp_orig,t,app.dt,nfr,md1,md2,nt); 
                                                
            x2r=omeganr;
            x4r=dampr;
            x5r=delayr;
            
            x1r=zeros(nfr,1);
            x3r=zeros(nfr,1);

            for ie=1:nfr    
                x1r(ie)=norm([Ar(ie) Br(ie)]);
                x3r(ie)=atan2(Ar(ie),Br(ie));
            end     
           
            if(nfr>=1)
            
                disp(' ');
                disp(' Results');
                disp(' ');
                disp(' Case   Amplitude   fn(Hz)       damp      Phase(rad)  Delay(sec) ');
                %
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %10.4f  %10.4f \n',...
                                            ie,x1r(ie),x2r(ie)/tp,x4r(ie),x3r(ie),x5r(ie));
                end    
                %
                x1r=fix_size(x1r);
                x2r=fix_size(x2r);
                x3r=fix_size(x3r);
                x4r=fix_size(x4r);
                x5r=fix_size(x5r);
                
                aaa=[x1r x2r x3r x4r x5r];
                
                bbb=sortrows(aaa,2);
                
                sx1r=bbb(:,1);
                sx2r=bbb(:,2);
                sx3r=bbb(:,3);
                sx4r=bbb(:,4);
                sx5r=bbb(:,5);
                
                bbb(:,2)=bbb(:,2)/tp;
            
                setappdata(0,'curve_fit_table',bbb);
                
                ccc=sortrows(aaa,-1);
                
                ax1r=ccc(:,1);
                ax2r=ccc(:,2);
                ax3r=ccc(:,3);
                ax4r=ccc(:,4);
                ax5r=ccc(:,5);
                
                % % % % % %
                
                disp(' ');
                disp(' Results, sorted by frequency');
                disp(' ');
                disp(' Case   Amplitude   fn(Hz)       damp      Phase(rad)  Delay(sec) ');
                %
                x2r_d_tp=zeros(nfr,1);
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %10.4f  %10.4f \n',...
                                            ie,sx1r(ie),sx2r(ie)/tp,sx4r(ie),sx3r(ie),sx5r(ie));
                    x2r_d_tp(ie)=sx2r(ie)/tp;

                    sx1r(ie)=str2num(sprintf('%7.3g',sx1r(ie)));
                    x2r_d_tp(ie)=str2num(sprintf('%7.4g',x2r_d_tp(ie)));
                    sx4r(ie)=str2num(sprintf('%7.3g',sx4r(ie)));
                    sx3r(ie)=str2num(sprintf('%7.3g',sx3r(ie)));  
                    sx5r(ie)=str2num(sprintf('%7.3g',sx5r(ie)));             
                end    

                Case = 1:nfr;
                Amplitude = sx1r;
                fn_Hz = x2r_d_tp;
                damp_ratio = sx4r;
                Phase_rad = sx3r;
                Delay_sec = sx5r;
    
                Case=fix_size(Case);
                Amplitude=fix_size(Amplitude);
                fn_Hz=fix_size(fn_Hz);
                damp_ratio=fix_size(damp_ratio);
                Phase_rad=fix_size(Phase_rad);
                Delay_sec=fix_size(Delay_sec);
    
                A = table(Case,Amplitude,fn_Hz,damp_ratio,Phase_rad,Delay_sec);
                output_name=app.OutputSignalNameEditField.Value;
            
                for ijk=1:1000
                    try
                        filename = sprintf('%s_%d.xlsx',output_name,ijk);
                        writetable(A,filename,'WriteMode','overwritesheet');
                        break;
                    catch
                        disp('writetable A failed');
                    end
                end
                
                % % % % % %
            
                disp(' ');
                disp(' Results, sorted by Amplitude');
                disp(' ');
                disp(' Case   Amplitude   fn(Hz)       damp      Phase(rad)  Delay(sec) ');
                %
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %10.4f  %10.4f \n',...
                                            ie,ax1r(ie),ax2r(ie)/tp,ax4r(ie),ax3r(ie),ax5r(ie));
                end    
            
            else
                warndlg('Minimum number of frequencies is 1')
                return;
            end
                
            tmax=1.0e+90;
                
            LM=length(s_tim);
                
            for i=1:LM
                terr=abs(s_tim(i)-t(1));
                if(terr<tmax)
                    tmax=terr;    
                end
            end
                
            t=fix_size(t);
            syn=fix_size(syn);
                
            if(nf==1)
                hpf_data_sh=fix_size(hpf_data_sh);
                syn=syn+hpf_data_sh;
            end
                
            ystring=app.YS; 
                
            app.transient_th=[t syn];
            app.residual_th=[t residual];

            sz1=size(app.THM_orig);
            sz2=size(app.THM);

            if(sz1(1)~=sz2(1))
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.THM_orig(:,1),app.THM_orig(:,2),'b');
                xlabel('Time (sec)');
                hold on
                plot(t,syn,'r');
                xlabel(' Time (sec) ');
                ylabel(ystring);
                legend ('Input Data','Transient Synthesis');
                title('Transient Curve-fit');
                zoom on;
                hold off;     
            end
                
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(s_tim,s_amp,'b');
            xlabel('Time (sec)');
            hold on
            plot(t,syn,'r');
            xlabel(' Time (sec) ');
            ylabel(ystring);
            legend ('Input Data','Transient Synthesis');
            title('Transient Curve-fit');
            zoom on;
            hold off;
                
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.residual_th(:,1),app.residual_th(:,2),'b');    
            title('Residual');
            xlabel(' Time (sec) ');
            ylabel(ystring);
            zoom on;
                
            if(nf==1)
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                hold on
                
                plot(app.THM(:,1),app.THM(:,2),'b');    
                plot(app.lpf_data(:,1),app.lpf_data(:,2),'r');
                legend ('Input Data','Rigid Body Est');    
                
                title('Rigid Body Acceleration Estimate');
                xlabel(' Time (sec) ');
                ylabel(ystring)
                zoom on;
                hold off;    
       
                rpt=app.lpf_data;         
                rpt(:,2)=rpt(:,2)+syn;
                
                app.rigid_plus_transient_th=rpt;
                app.rigid_plus_residual_th=[s_tim app.lpf_data(:,2)+app.residual_th(:,2)];
            
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.rigid_plus_transient_th(:,1),app.rigid_plus_transient_th(:,2),'b');
                title('Rigid Body Acceleration + Transient Estimate');
                xlabel(' Time (sec) ');
                ylabel(ystring);
                zoom on;
                             
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                
                plot(app.original_minus_rigid_th(:,1),app.original_minus_rigid_th(:,2),'b');
                title('Original - Rigid Body Acceleration');
                xlabel(' Time (sec) ');
                ylabel(ystring);
                zoom on;   

                hp=figure(app.fig_num);
                app.fig_num=app.fig_num+1;
            
                subplot(4,1,1);
                plot(app.THM(:,1),app.THM(:,2));
                ylabel(ystring);
                title('Input Data');
                qqq=get(gca,'ylim');
                qmax=max(abs(qqq));
                ylim([-qmax,qmax]);
                grid on;
                grid minor;
                
                subplot(4,1,2);
                plot(app.lpf_data(:,1),app.lpf_data(:,2));
                ylabel(ystring);
                title('Rigid Body Estimate');
                ylim([-qmax,qmax]);
                grid on;    
                grid minor;   
             
                subplot(4,1,3);
                plot(t,syn);
                ylabel(ystring);
                title('Transient Curve-fit');
                ylim([-qmax,qmax]);
                grid on;  
                grid minor;      
                
                subplot(4,1,4);
                plot(app.residual_th(:,1),app.residual_th(:,2));
                xlabel('Time (sec)');
                ylabel(ystring);
                title('Residual');
                ylim([-qmax,qmax]);
                grid on;
                grid minor;     
                
                set(hp, 'Position', [50 50 650 700]); 
                
            end

            name_synthesis=sprintf('%s_synthesis',output_name);
            name_residual=sprintf('%s_residual',output_name);
            assignin('base',name_synthesis,app.transient_th);
            assignin('base',name_residual,app.residual_th);

            sss=sprintf(' ** Output data written to Excel file:  %s ** \n\n Matlab Save Files \n\n',filename);
            sss=sprintf('%s  Synthesis: %s \n',sss,name_synthesis);
            sss=sprintf('%s   Residual: %s \n',sss,name_residual);

            if(nf==1)
                name1=sprintf('%s_rb',output_name);
                name2=sprintf('%s_rbpt',output_name);
                name3=sprintf('%s_rbpr',output_name);
                name4=sprintf('%s_omrb',output_name);

                assignin('base',name_synthesis,app.lpf_data);
                assignin('base',name_residual,app.rigid_plus_transient_th);            
                assignin('base',name_synthesis,app.rigid_plus_residual_th);
                assignin('base',name_residual,app.original_minus_rigid_th);

                sss=sprintf('%s  Rigid Body: %s \n',sss,name1);
                sss=sprintf('%s  Rigid Body + Transient: %s \n ',sss,name2);
                sss=sprintf('%s  Rigid Body + Residual: %s \n',sss,name3);
                sss=sprintf('%s  Original - Rigid Body: %s',sss,name4);
            end

            msgbox(sss);
            
            disp(' ');
            toc

            try
                pause(1);
                winopen(filename);
            catch
                disp('winopen failed');
            end   

        end    

        function et_mean_hpf(app)

            ta=app.amp_i;
            
            t=ta(:,1);
            y=ta(:,2); 
            a=y;
            
            n = length(y);
            
            maxw=floor(double(n)/2);
           
            app.dt=mean(diff(t));
            sr=1/app.dt;
            
            fcross=str2num(app.CrossOverFreqHzEditField.Value);
            
            app.q=0.728*(sr/fcross);
            
            w= 2*floor(app.q/2)+1;
            
            if(w>maxw)
                w= 2*floor(app.q/2)-1; 
            end
            
            fprintf('\n  window size=%d \n\n',w);
            %
            k=fix(double(w-1)/2.);
            %
            
            last=n;
            
                for i=1:last
            %
                    ave=0.;
                    n=0;
            %
                    for j=(i-k):(i+k)
            %
                        if(j>=1 && j<=last )
                            ave=ave+a(j);
                            n=n+1;
                        end	
                    end
                    if(n>1)
                        a(i)=ave/double(n);
                    end
                end
                a=y-a;
            %
            t=fix_size(t);
            a=fix_size(a);
            app.hpf_data=[t a];   
           
        end    

        function et_mean_lpf(app)

            app.fc=str2num(app.LowpassFreqHzEditField_2.Value);
            
            t=app.THM(:,1);
            y=app.THM(:,2);
            a=y;
            
            tmx=max(t);
            tmi=min(t);
            n = length(y);
            app.dt=(tmx-tmi)/(n-1);
            sr=1/app.dt;
            
            maxw=round(double(n)/2);
            
            app.q=0.400*(sr/app.fc);  % primary goal lpf
                        
            w= 2*floor(app.q/2)+1;
            if(w>maxw)
                w= 2*floor(app.q/2)-1; 
            end
            if(w<3)
                w=3;
            end            
            
            fprintf('\n  window size=%d \n\n',w);
              
            k=fix(double(w-1)/2.);
            last=n;
            
            %  np=1;  % number of passes
            
            for i=1:last
            
                ave=0.;
                n=0;
            
                for j=(i-k):(i+k)
            
                    if(j>=1 && j<=last )
                        ave=ave+a(j);
                        n=n+1;
                    end	
                end
                if(n>1)
                    a(i)=ave/double(n);
                end
            end
              
            t=fix_size(t);
            a=fix_size(a);
            app.lpf_data=[t a];   

        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fig_num=1;
            ClosePlots(app);
            update(app);
            TimeChoice(app);
            AnalysisChange(app);
            Butterworth_type(app);
            DefineNonFilter(app);
            SineTones(app);      
            EDS_filter(app);
            WaveletNumber(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            PreProcess(app);

            PlotInputButtonPushed(app, event);

            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [app.listbox_choice]=find_index(Items,im);

            if(app.listbox_choice==2)
                Butterworth_Filter(app);
            end
            if(app.listbox_choice==3)
                Bessel_Filter(app);  
            end    
            if(app.listbox_choice==4)
                Mean_Filter(app);
            end
            if(app.listbox_choice==5)
                Median_Filter(app);             
            end    
            if(app.listbox_choice==6)
                Remove_Sine_Tones(app);             
            end
            if(app.listbox_choice==7)
                Extract_Transient(app);                 
            end    
            if(app.listbox_choice==8)
                Wavelet_Filter(app);             
            end
            if(app.listbox_choice==9)               
                CFC_Filter(app);   
            end 
            if(app.listbox_choice==10)
                SVD_Filter(app);
            end
            if(app.listbox_choice==11)
                Sigma_Filter(app);            
            end    
            if(app.listbox_choice==12)
                disp('FFT')
                PreProcess_FFT(app);
                disp('finish preprocess')
                [app.freq,app.full,app.phase,app.complex_FT]=full_FFT_core_alt(app.amp,app.num,app.df);
                app.magnitude_FT=[app.freq app.full];
                app.magnitude_phase_FT=[app.freq app.full app.phase];
                PostProcess_FFT(app);
            end    
            app.listbox_choice

        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            TimeChoice(app);
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            AnalysisChange(app);
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
            AnalysisChange(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            disp('ref 1')

            m=app.TabGroup.SelectedTab;
            n=m.Title;

            if(contains(n,'Read'))
                n=1;
            end     
            if(strcmp(n,'a'))
                n=2;
            end              
            if(strcmp(n,'b'))
                n=3;
            end     
            if(strcmp(n,'c'))
                n=4;
            end              
            if(strcmp(n,'d'))
                n=5;
            end 
            if(strcmp(n,'e'))
                n=6;
            end              
            if(strcmp(n,'f'))
                n=7;
            end     
            if(strcmp(n,'g'))
                n=8;
            end              
            if(strcmp(n,'h'))
                n=9;
            end   
            if(strcmp(n,'i'))
                n=10;
            end      
            if(strcmp(n,'j'))
                n=11;
            end            
            if(strcmp(n,'k'))
                n=12;
            end       

            try
                app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
                app.listbox_choice=n;
            catch
                disp('SelectAnalysisListBox failed');
                app.SelectAnalysisListBox.Items
                n               
            end    
        end

        % Button pushed function: PlotInputButton
        function PlotInputButtonPushed(app, event)
           
            try
                PreProcess(app);
            catch
                warndlg('preprocess failed');
                return;
            end

            try
                sz=size(app.THM);
            catch
                warndlg('app.THM');
            end    

            try
                figure(1)
                
                if(sz(2)==1)
                    plot(app.THM(:,1))
                else    
                    plot(app.THM(:,1),app.THM(:,2));
                    xlabel('Time (sec)');
                end

                title('Input Time History')
                grid on;
                YL=app.InputYaxisLabelEditField.Value;
                ylabel(YL);
                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);
            catch
                warndlg('Input Plot Failed');
                return;
            end
        end

        % Value changed function: TypeListBox
        function TypeListBoxValueChanged(app, event)
            Butterworth_type(app);
        end

        % Button pushed function: DisplayTransferFunctionButton
        function DisplayTransferFunctionButtonPushed(app, event)
            Butterworth_filter_transfer(app);
        end

        % Value changed function: OrderListBox
        function OrderListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: RefilteringforPhaseCorrectionListBox
        function RefilteringforPhaseCorrectionListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: LowerFrequencyHzEditField
        function LowerFrequencyHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: UpperFrequencyHzEditField
        function UpperFrequencyHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Button pushed function: DisplayTransferFunctionButton_2
        function DisplayTransferFunctionButton_2Pushed(app, event)
            Bessel_Filter_Transfer(app);
        end

        % Value changing function: LowpassFreqHzEditField
        function LowpassFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: DefineNonFilterTimeZoneListBox
        function DefineNonFilterTimeZoneListBoxValueChanged(app, event)
            DefineNonFilter(app);
        end

        % Value changed function: FilterTypeListBox
        function FilterTypeListBoxValueChanged(app, event)
            ClosePlots(app);            
        end

        % Value changed function: NumberofTonesListBox
        function NumberofTonesListBoxValueChanged(app, event)
             ClosePlots(app);
             SineTones(app);
        end

        % Button pushed function: SetHarmonicsButton
        function SetHarmonicsButtonPushed(app, event)
            app.n_tones=str2num(app.NumberofTonesListBox.Value);
            N=app.n_tones;
            A=char(app.UITable.Data);
            B=str2num(A);
                
            app.freq=B(1:1);

            app.n_tones=str2num(app.NumberofTonesListBox.Value);
            for i = 1:app.n_tones
               for j=1:3
                  data_s{i,j} = '';     
               end
               data_s{i,1}=sprintf('%7.4g',app.freq*i);
            end
            app.UITable.Data=data_s;   
        end

        % Value changed function: 
        % LowpassFiltertoExtractRigidbodyAccelerationListBox
        function LowpassFiltertoExtractRigidbodyAccelerationListBoxValueChanged(app, event)
            EDS_filter(app);
        end

        % Value changed function: NumberofFreqCasesListBox
        function NumberofFreqCasesListBoxValueChanged(app, event)
            WaveletNumber(app);
        end

        % Callback function
        function CalculateFFTButtonPushed(app, event)
            PreProcess_FFT(app);
            [app.freq,app.full,app.phase,app.complex_FT]=full_FFT_core_alt(app.amp,app.num,app.df);
            app.magnitude_FT=[app.freq app.full];
            app.magnitude_phase_FT=[app.freq app.full app.phase];
            PostProcess(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            AnalysisChange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterUIFigure = uifigure('Visible', 'off');
            app.FilterUIFigure.Name = 'Filter';
            app.ReturnButton = uibutton(app.FilterUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.filter_Appver10byTomIrvineLabel = uilabel(app.FilterUIFigure);
            app.filter_Appver10byTomIrvineLabel.Text = 'filter_App     ver 1.2  by Tom Irvine         The input array must have two columns: time(sec) & amplitude.';
            app.CalculateButton = uibutton(app.FilterUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.FilterUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ReadDataTab = uitab(app.TabGroup);
            app.ReadDataTab.Title = 'Read Data';
            app.listbox_method = uilistbox(app.ReadDataTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ReadDataTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.SignalProcessingListBoxLabel = uilabel(app.ReadDataTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.ReadDataTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.SelectInputArrayListBoxLabel = uilabel(app.ReadDataTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ReadDataTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.StartTimesecEditFieldLabel = uilabel(app.ReadDataTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.ReadDataTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.ReadDataTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.ReadDataTab, 'numeric');
            app.UpdateListButton = uibutton(app.ReadDataTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.aTab = uitab(app.TabGroup);
            app.aTab.Title = 'a';
            app.ButterworthFilterLabel = uilabel(app.aTab);
            app.ButterworthFilterLabel.Text = 'Butterworth Filter';
            app.DisplayTransferFunctionButton = uibutton(app.aTab, 'push');
            app.DisplayTransferFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTransferFunctionButtonPushed, true);
            app.DisplayTransferFunctionButton.Text = 'Display Transfer Function ';
            app.OrderListBoxLabel = uilabel(app.aTab);
            app.OrderListBoxLabel.Text = 'Order';
            app.OrderListBox = uilistbox(app.aTab);
            app.OrderListBox.Items = {'Fourth', 'Sixth', 'Eighth'};
            app.OrderListBox.ValueChangedFcn = createCallbackFcn(app, @OrderListBoxValueChanged, true);
            app.OrderListBox.Value = 'Sixth';
            app.RefilteringforPhaseCorrectionLabel = uilabel(app.aTab);
            app.RefilteringforPhaseCorrectionLabel.Text = {'Refiltering for '; 'Phase Correction'};
            app.RefilteringforPhaseCorrectionListBox = uilistbox(app.aTab);
            app.RefilteringforPhaseCorrectionListBox.Items = {'Yes', 'No'};
            app.RefilteringforPhaseCorrectionListBox.ValueChangedFcn = createCallbackFcn(app, @RefilteringforPhaseCorrectionListBoxValueChanged, true);
            app.RefilteringforPhaseCorrectionListBox.Value = 'Yes';
            app.TypeLabel = uilabel(app.aTab);
            app.TypeLabel.Text = 'Type';
            app.TypeListBox = uilistbox(app.aTab);
            app.TypeListBox.Items = {'Low-pass', 'High-pass', 'Band-pass', 'Band-stop'};
            app.TypeListBox.ValueChangedFcn = createCallbackFcn(app, @TypeListBoxValueChanged, true);
            app.TypeListBox.Value = 'Low-pass';
            app.LowerFrequencyHzEditFieldLabel = uilabel(app.aTab);
            app.LowerFrequencyHzEditFieldLabel.Text = 'Lower Frequency (Hz)';
            app.LowerFrequencyHzEditField = uieditfield(app.aTab, 'text');
            app.LowerFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowerFrequencyHzEditFieldValueChanging, true);
            app.UpperFrequencyHzEditFieldLabel = uilabel(app.aTab);
            app.UpperFrequencyHzEditFieldLabel.Text = 'Upper Frequency (Hz)';
            app.UpperFrequencyHzEditField = uieditfield(app.aTab, 'text');
            app.UpperFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @UpperFrequencyHzEditFieldValueChanging, true);
            app.bTab = uitab(app.TabGroup);
            app.bTab.Title = 'b';
            app.BesselTwoPoleFilterLabel = uilabel(app.bTab);
            app.BesselTwoPoleFilterLabel.Text = {'Bessel Two-Pole Filter  '; ''; 'The lowpass frequency corresponds to -3 dB in the  transfer function magnitude.'};
            app.LowpassFreqHzEditFieldLabel = uilabel(app.bTab);
            app.LowpassFreqHzEditFieldLabel.Text = 'Low-pass Freq (Hz)';
            app.LowpassFreqHzEditField = uieditfield(app.bTab, 'text');
            app.LowpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowpassFreqHzEditFieldValueChanging, true);
            app.DisplayTransferFunctionButton_2 = uibutton(app.bTab, 'push');
            app.DisplayTransferFunctionButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplayTransferFunctionButton_2Pushed, true);
            app.DisplayTransferFunctionButton_2.Text = 'Display Transfer Function ';
            app.cTab = uitab(app.TabGroup);
            app.cTab.Title = 'c';
            app.MeanFilterLabel = uilabel(app.cTab);
            app.MeanFilterLabel.Text = 'Mean Filter';
            app.WindowSizeListBox_2Label = uilabel(app.cTab);
            app.WindowSizeListBox_2Label.Text = 'Window Size';
            app.WindowSizeListBox_Mean = uilistbox(app.cTab);
            app.WindowSizeListBox_Mean.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', '27', '29', '31', '33', '35', '37', '39', '41', '43', '45', '47', '49', '51', '53', '55', '57', '59', '61', '63', '65', '67', '69', '71', '73', '75', '77', '79', '81', '83', '85', '87', '89', '91', '93', '95', '97', '99', '101', '103', '105', '107', '109', '111', '113', '115', '117', '119', '121', '123', '125', '127', '129', '131', '133', '135'};
            app.WindowSizeListBox_Mean.Value = '3';
            app.NumberofPassesLabel = uilabel(app.cTab);
            app.NumberofPassesLabel.Text = {'Number '; 'of Passes'};
            app.NumberofPassesListBox = uilistbox(app.cTab);
            app.NumberofPassesListBox.Items = {'1', '2', '3', '4', '5'};
            app.NumberofPassesListBox.Value = '1';
            app.dTab = uitab(app.TabGroup);
            app.dTab.Title = 'd';
            app.MeidanFilterLabel = uilabel(app.dTab);
            app.MeidanFilterLabel.Text = 'Meidan Filter';
            app.WindowSizeListBoxLabel = uilabel(app.dTab);
            app.WindowSizeListBoxLabel.Text = 'Window Size';
            app.WindowSizeListBox = uilistbox(app.dTab);
            app.WindowSizeListBox.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21'};
            app.WindowSizeListBox.Value = '3';
            app.eTab = uitab(app.TabGroup);
            app.eTab.Title = 'e';
            app.RemoveSineTonesBWisBandwidthLabel = uilabel(app.eTab);
            app.RemoveSineTonesBWisBandwidthLabel.Text = 'Remove Sine Tones      BW is Bandwidth';
            app.TrialsperFrequencyEditFieldLabel = uilabel(app.eTab);
            app.TrialsperFrequencyEditFieldLabel.Text = {'Trials per '; 'Frequency'};
            app.TrialsperFrequencyEditField = uieditfield(app.eTab, 'text');
            app.TrialsperFrequencyEditField.Value = '2000';
            app.NumberofTonesLabel = uilabel(app.eTab);
            app.NumberofTonesLabel.Text = {'Number of '; 'Tones'};
            app.NumberofTonesListBox = uilistbox(app.eTab);
            app.NumberofTonesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofTonesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofTonesListBoxValueChanged, true);
            app.NumberofTonesListBox.Value = '1';
            app.UITable = uitable(app.eTab);
            app.UITable.ColumnName = {'Freq (Hz)'; 'BW (Hz)'; 'Number'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SetHarmonicsButton = uibutton(app.eTab, 'push');
            app.SetHarmonicsButton.ButtonPushedFcn = createCallbackFcn(app, @SetHarmonicsButtonPushed, true);
            app.SetHarmonicsButton.Text = 'Set Harmonics';
            app.SetintegerharmonicsbasedonfirstfrequencyLabel = uilabel(app.eTab);
            app.SetintegerharmonicsbasedonfirstfrequencyLabel.Text = {'Set integer harmonics '; 'based on first frequency.'};
            app.fTab = uitab(app.TabGroup);
            app.fTab.Title = 'f';
            app.ExtractTransientLabel = uilabel(app.fTab);
            app.ExtractTransientLabel.Text = 'Extract Transient';
            app.NumberofDampedSinesEditFieldLabel = uilabel(app.fTab);
            app.NumberofDampedSinesEditFieldLabel.Text = {'Number of '; 'Damped Sines'};
            app.NumberofDampedSinesEditField = uieditfield(app.fTab, 'text');
            app.TrialsperFrequencyEditField_2Label = uilabel(app.fTab);
            app.TrialsperFrequencyEditField_2Label.Text = {'Trials per '; 'Frequency'};
            app.TrialsperFrequencyEditField_2 = uieditfield(app.fTab, 'text');
            app.TrialsperFrequencyEditField_2.Value = '1000';
            app.CrossOverFreqHzEditFieldLabel = uilabel(app.fTab);
            app.CrossOverFreqHzEditFieldLabel.Text = 'Cross-Over Freq (Hz)';
            app.CrossOverFreqHzEditField = uieditfield(app.fTab, 'text');
            app.DampingRatioLabel = uilabel(app.fTab);
            app.DampingRatioLabel.Text = 'Damping Ratio';
            app.MinEditField_2Label = uilabel(app.fTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.fTab, 'text');
            app.MinEditField_2.Value = '0.01';
            app.MaxEditFieldLabel = uilabel(app.fTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.fTab, 'text');
            app.MaxEditField.Value = '0.30';
            app.LowpassFiltertoExtractRigidbodyAccelerationLabel = uilabel(app.fTab);
            app.LowpassFiltertoExtractRigidbodyAccelerationLabel.Text = {'Lowpass Filter to '; 'Extract  Rigid-body '; 'Acceleration'};
            app.LowpassFiltertoExtractRigidbodyAccelerationListBox = uilistbox(app.fTab);
            app.LowpassFiltertoExtractRigidbodyAccelerationListBox.Items = {'Yes', 'No'};
            app.LowpassFiltertoExtractRigidbodyAccelerationListBox.ValueChangedFcn = createCallbackFcn(app, @LowpassFiltertoExtractRigidbodyAccelerationListBoxValueChanged, true);
            app.LowpassFiltertoExtractRigidbodyAccelerationListBox.Value = 'No';
            app.LowpassFreqHzEditField_2Label = uilabel(app.fTab);
            app.LowpassFreqHzEditField_2Label.Text = {'Lowpass'; 'Freq (Hz)'};
            app.LowpassFreqHzEditField_2 = uieditfield(app.fTab, 'text');
            app.LowpassFreqHzEditField_2.Value = '0.7';
            app.gTab = uitab(app.TabGroup);
            app.gTab.Title = 'g';
            app.WaveletFilteringLabel = uilabel(app.gTab);
            app.WaveletFilteringLabel.Text = {'Wavelet Filtering.  This script decomposes a time history into a series '; 'of wavelets for shaker shock testing or analysis.'};
            app.NumberofFreqCasesListBoxLabel = uilabel(app.gTab);
            app.NumberofFreqCasesListBoxLabel.Text = {'Number of '; 'Freq Cases'};
            app.NumberofFreqCasesListBox = uilistbox(app.gTab);
            app.NumberofFreqCasesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofFreqCasesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofFreqCasesListBoxValueChanged, true);
            app.NumberofFreqCasesListBox.Value = '1';
            app.UITable_2 = uitable(app.gTab);
            app.UITable_2.ColumnName = {'Freq (Hz)'; 'Std Dev (Hz)'; 'Number'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.DelayTimesecLabel = uilabel(app.gTab);
            app.DelayTimesecLabel.Text = {'Delay Time '; '(sec)'};
            app.DelayTimesecEditField = uieditfield(app.gTab, 'text');
            app.DelayTimesecEditField.Value = '0';
            app.MinEditField_3Label = uilabel(app.gTab);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_w = uieditfield(app.gTab, 'text');
            app.MinEditField_w.Value = '3';
            app.MaxEditField_2Label = uilabel(app.gTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_w = uieditfield(app.gTab, 'text');
            app.MaxEditField_w.Value = '53';
            app.NumberofWaveletsLabel = uilabel(app.gTab);
            app.NumberofWaveletsLabel.Text = 'Number of  Wavelets';
            app.TrialsperWaveletEditFieldLabel = uilabel(app.gTab);
            app.TrialsperWaveletEditFieldLabel.Text = 'Trials per Wavelet';
            app.TrialsperWaveletEditField = uieditfield(app.gTab, 'text');
            app.TrialsperWaveletEditField.Value = '4000';
            app.UnitsListBoxLabel = uilabel(app.gTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.gTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm ', 'm/sec^2, cm/sec, mm '};
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.hTab = uitab(app.TabGroup);
            app.hTab.Title = 'h';
            app.ChannelFrequencyClassCFCLabel = uilabel(app.hTab);
            app.ChannelFrequencyClassCFCLabel.Text = {'Channel Frequency Class (CFC)  '; ''; 'This script applies an SAE CFC Butterworth filter to a time history.  '; ''; 'Note that the cut-off frequency for a CFC 60 is actually about 100 Hz '; '(cut-off equals about 1.667 times CFC).'};
            app.FilterTypeListBoxLabel = uilabel(app.hTab);
            app.FilterTypeListBoxLabel.Text = 'Filter Type';
            app.FilterTypeListBox = uilistbox(app.hTab);
            app.FilterTypeListBox.Items = {'CFC 60', 'CFC 180', 'CFC 600', 'CFC 1000'};
            app.FilterTypeListBox.ValueChangedFcn = createCallbackFcn(app, @FilterTypeListBoxValueChanged, true);
            app.FilterTypeListBox.Value = 'CFC 60';
            app.iTab = uitab(app.TabGroup);
            app.iTab.Title = 'i';
            app.SVDFilterLabel = uilabel(app.iTab);
            app.SVDFilterLabel.Text = 'Singular Value Decomposition filter for short time histories of less than 6000 points';
            app.TabGroup2 = uitabgroup(app.iTab);
            app.SVDTab = uitab(app.TabGroup2);
            app.SVDTab.Title = 'SVD';
            app.MaximumNumberofPointstoIncludeEditFieldLabel = uilabel(app.SVDTab);
            app.MaximumNumberofPointstoIncludeEditFieldLabel.Text = 'Maximum Number of Points to Include';
            app.MaximumNumberofPointstoIncludeEditField = uieditfield(app.SVDTab, 'numeric');
            app.MaximumNumberofPointstoIncludeEditField.Value = 6000;
            app.FilterTab = uitab(app.TabGroup2);
            app.FilterTab.Title = 'Filter';
            app.SingularValueThresholdRatioEditFieldLabel = uilabel(app.FilterTab);
            app.SingularValueThresholdRatioEditFieldLabel.Text = 'Singular Value Threshold Ratio';
            app.SingularValueThresholdRatioEditField = uieditfield(app.FilterTab, 'numeric');
            app.SingularValueThresholdRatioEditField.Value = 0.044;
            app.jTab = uitab(app.TabGroup);
            app.jTab.Title = 'j';
            app.SigmaFiltertoRemoveNoiseSpikesLabel = uilabel(app.jTab);
            app.SigmaFiltertoRemoveNoiseSpikesLabel.Text = 'Sigma Filter to Remove Noise Spikes';
            app.NumberofStagesLabel = uilabel(app.jTab);
            app.NumberofStagesLabel.Text = {'Number of '; 'Stages'};
            app.NumberofStagesListBox = uilistbox(app.jTab);
            app.NumberofStagesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.NumberofStagesListBox.Value = '6';
            app.WindowSizesecEditFieldLabel = uilabel(app.jTab);
            app.WindowSizesecEditFieldLabel.Text = {'Window '; 'Size (sec)'};
            app.WindowSizesecEditField = uieditfield(app.jTab, 'text');
            app.SigmaThresholdLabel = uilabel(app.jTab);
            app.SigmaThresholdLabel.Text = {'Sigma '; 'Threshold'};
            app.SigmaThresholdEditField = uieditfield(app.jTab, 'text');
            app.SigmaThresholdEditField.Value = '5';
            app.DefineNonFilterTimeZoneListBoxLabel = uilabel(app.jTab);
            app.DefineNonFilterTimeZoneListBoxLabel.Text = {'Define Non-Filter '; 'Time Zone'};
            app.DefineNonFilterTimeZoneListBox = uilistbox(app.jTab);
            app.DefineNonFilterTimeZoneListBox.Items = {'Yes', 'No'};
            app.DefineNonFilterTimeZoneListBox.ValueChangedFcn = createCallbackFcn(app, @DefineNonFilterTimeZoneListBoxValueChanged, true);
            app.DefineNonFilterTimeZoneListBox.Value = 'Yes';
            app.StartTimesecEditField_2Label = uilabel(app.jTab);
            app.StartTimesecEditField_2Label.Text = 'Start Time (sec)';
            app.StartTimesecEditField_2 = uieditfield(app.jTab, 'text');
            app.EndTimesecEditField_2Label = uilabel(app.jTab);
            app.EndTimesecEditField_2Label.Text = 'End Time (sec)';
            app.EndTimesecEditField_2 = uieditfield(app.jTab, 'text');
            app.kTab = uitab(app.TabGroup);
            app.kTab.Title = 'k';
            app.FFTLabel = uilabel(app.kTab);
            app.FFTLabel.Text = 'FFT';
            app.MeanRemovalListBoxLabel = uilabel(app.kTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.kTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.kTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.kTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning'};
            app.WindowListBox.Value = 'Rectangular';
            app.PlotMaxFreqHzEditFieldLabel = uilabel(app.kTab);
            app.PlotMaxFreqHzEditFieldLabel.Text = 'Plot Max Freq (Hz)';
            app.PlotMaxFreqHzEditField = uieditfield(app.kTab, 'numeric');
            app.InputYaxisLabelEditFieldLabel = uilabel(app.FilterUIFigure);
            app.InputYaxisLabelEditFieldLabel.Text = 'Input Y-axis Label';
            app.InputYaxisLabelEditField = uieditfield(app.FilterUIFigure, 'text');
            app.InputYaxisLabelEditField.Value = 'Accel (G)';
            app.PlotInputButton = uibutton(app.FilterUIFigure, 'push');
            app.PlotInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputButtonPushed, true);
            app.PlotInputButton.Text = 'Plot Input';
            app.ResultsTextAreaLabel = uilabel(app.FilterUIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.FilterUIFigure);
            app.OutputSignalNameEditFieldLabel = uilabel(app.FilterUIFigure);
            app.OutputSignalNameEditFieldLabel.Text = 'Output Signal Name';
            app.OutputSignalNameEditField = uieditfield(app.FilterUIFigure, 'text');
            app.OutputSignalNameEditField.Value = 'signal_out';
            app.PerformAnalysisButton = uibutton(app.FilterUIFigure, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.SelectAnalysisListBoxLabel = uilabel(app.FilterUIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.FilterUIFigure);
            app.SelectAnalysisListBox.Items = {'Read Data', 'Butterworth Filter', 'Bessel Filter ', 'Mean Filter', 'Median Filter', 'Remove Sine Tones', 'Extract Transient', 'Wavelet Filtering', 'Channel Frequency Class (CFC)', 'SVD Filter', 'Sigma Filter to Remove Noise Spikes', 'FFT'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Read Data';
            app.AnalyzeButton = uibutton(app.FilterUIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            % Show the figure after all components are created
            app.FilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filter_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FilterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FilterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterUIFigure)
        end
    end
end