classdef freely_hung_panel_diffuse_field_multi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel2              matlab.ui.container.Panel
        text17                matlab.ui.control.Label
        text21                matlab.ui.control.Label
        UniformLossFactor     matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        edit_scf              matlab.ui.control.EditField
        listbox_band          matlab.ui.control.ListBox
        text58                matlab.ui.control.Label
        edit_input_array      matlab.ui.control.EditField
        text55                matlab.ui.control.Label
        text54                matlab.ui.control.Label
        listbox_pu            matlab.ui.control.ListBox
        edit_W                matlab.ui.control.EditField
        text_W                matlab.ui.control.Label
        edit_L                matlab.ui.control.EditField
        text_L                matlab.ui.control.Label
        text47                matlab.ui.control.Label
        listbox_gas           matlab.ui.control.ListBox
        edit_c                matlab.ui.control.EditField
        text_gas_c            matlab.ui.control.Label
        text_thick            matlab.ui.control.Label
        edit_thick            matlab.ui.control.EditField
        edit_gas_md           matlab.ui.control.EditField
        text_gas_md           matlab.ui.control.Label
        listbox_structure     matlab.ui.control.ListBox
        text35                matlab.ui.control.Label
        text3                 matlab.ui.control.Label
        listbox_units         matlab.ui.control.ListBox
        text_mu               matlab.ui.control.Label
        edit_mu               matlab.ui.control.EditField
        edit_md               matlab.ui.control.EditField
        text_md               matlab.ui.control.Label
        edit_em               matlab.ui.control.EditField
        text_em               matlab.ui.control.Label
        text_material         matlab.ui.control.Label
        listbox_material      matlab.ui.control.ListBox
        pushbutton_calculate  matlab.ui.control.Button
        pushbutton_return     matlab.ui.control.Button
        text2                 matlab.ui.control.Label
        uipanel5              matlab.ui.container.Panel
        pushbutton_rr         matlab.ui.control.Button
        pushbutton_re         matlab.ui.control.Button
        ContextMenu           matlab.ui.container.ContextMenu
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        iu   % Description
        imat %
        ng   %
        THM  %
    end
    
    methods (Access = private)
        function change(app)

            Items = char(app.listbox_units.Items);
            im=app.listbox_units.Value;
            [app.iu]=find_index(Items,im);

            Items = char(app.listbox_material.Items);
            im=app.listbox_material.Value;
            [app.imat]=find_index(Items,im);
            
            Items = char(app.listbox_gas.Items);
            im=app.listbox_gas.Value;
            [app.ng]=find_index(Items,im);            
                      
            %%%
            
            app.text_material.Text='Select Material';
            
            app.text_thick.Visible='on';
            app.edit_thick.Visible='on';
            
     
            string_th{1}=sprintf('dB ref 20 micro Pa');
            
            if(app.iu==1)  % English
            
            
                app.text_em.Text='Elastic Modulus (psi)';
                app.text_md.Text='Mass Density (lbm/in^3)';
                app.text_thick.Text='Thickness (in)';
                app.text_L.Text='Length (in)';
                app.text_W.Text='Width (in)';
            
                app.text_gas_md.Text='Gas Mass Density (lbm/ft^3)';
                app.text_gas_c.Text='Gas Speed of Sound (ft/sec)';
            
            
                if(app.ng==1)
                    app.edit_c.Value='1125';
                    app.edit_gas_md.Value='0.076487';
                else
                    app.edit_c.Value=' ';
                    app.edit_gas_md.Value=' ';
                end
            
                string_th{2}=sprintf('psi rms');
            
            else
            
                app.text_em.Text='Elastic Modulus (GPa)';
                app.text_md.Text='Mass Density (kg/m^3)';
                app.text_thick.Text='Thickness (mm)';
                app.text_L.Text='Length (m)';
                app.text_W.Text='Width (m)';
            
                app.text_gas_md.Text='Gas Mass Density (kg/m^3)';
                app.text_gas_c.Text='Gas Speed of Sound (m/sec)';
            
            
                if(app.ng==1)
                    app.edit_c.Value='343';
                    app.edit_gas_md.Value='1.225';
                else
                    app.edit_c.Value=' ';
                    app.edit_gas_md.Value=' ';
                end
            
                string_th{2}=sprintf('Pa rms');
            
            end
            
            app.listbox_pu.Items=string_th;
            
            %%%%%%%%%%%%%%
            
            [elastic_modulus,mass_density,poisson]=six_materials(app.iu,app.imat);
                 
            if(app.imat<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
                    ss3=sprintf('%8.4g',poisson);
            else
                    ss1=' ';
                    ss2=' ';
                    ss3=' ';
            end
            
            app.edit_em.Value=ss1;
            app.edit_md.Value=ss2;
            app.edit_mu.Value=ss3;
        end
        
        function edit34_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_L (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_H_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_H (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_I_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_I (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_Ix_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_Ix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_Iy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_Iy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_area_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_area (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_em_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_em_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_fcr_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_fcr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_fct_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_fct (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_freq_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_freq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_h_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_h (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_lf_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_lf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_md_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_md_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_md_core_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_md_core (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_mu_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_mu_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_pressure_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_pressure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_r1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_r1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_r2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_r2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_thick_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_thick_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_width_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_width (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function intermediate_print(app, hObject, eventdata, handles)
            
                if(app.imat==1)
                    disp('Aluminum');
                end
                if(app.imat==2)
                    disp('Steel');
                end
                if(app.imat==3)
                    disp('Copper');
                end
                if(app.imat==4)
                    disp('G10');
                end
                if(app.imat==5)
                    disp('Other Material');
                end
        end
        
        function listbox8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_structure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_bc_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_bc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_cross_Callback(app, hObject, eventdata, handles)
            % hObject    handle to listbox_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_cross contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_cross
            change(app);
        end
        
        function listbox_cross_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox_material_2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_material_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function freely_hung_panel_diffuse_field_multi_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to freely_hung_panel_diffuse_field_multi (see VARARGIN)
            
            % Choose default command line output for freely_hung_panel_diffuse_field_multi
            handles.output = hObject;
            
            change(app);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_gas
        function listbox_gas_Callback(app, event)
            change(app)
        end

        % Value changed function: listbox_material
        function listbox_material_Callback(app, event)
            change(app)
        end

        % Value changed function: listbox_structure
        function listbox_structure_Callback(app, event)
            change(app)
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            change(app)
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            tpi=2*pi;
            
            try
                FS=get(handles.edit_input_array,'String');
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try again.')
                return;
            end
            
            sz=size(app.THM);

            if(sz(2)==2)
                ulf=app.UniformLossFactor.Value;
                app.THM(:,3)=ulf;
            end

            disp(' ');
            disp(' * * * ');
            disp('  ');
            
            pu=get(handles.listbox_pu,'Value');
            
            fc=app.THM(:,1);
            lf=app.THM(:,3);
            
            NL=length(fc);
            
            if(pu==1)
                dB=app.THM(:,2);
            
                [pressure]=convert_dB_pressure_alt(dB,app.iu);
            else
                pressure=app.THM(:,2);
            end

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            em=str2num(get(handles.edit_em,'String'));
            md=str2num(get(handles.edit_md,'String'));
            mu=str2num(get(handles.edit_mu,'String'));
            
            c=str2num(get(handles.edit_c,'String'));
            gas_md=str2num(get(handles.edit_gas_md,'String'));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                md=md/386;
                c=c*12;
            
                [gas_md]=lbm_per_ft3_to_lbf_sec2_per_in4(gas_md);
            
            else
                [em]=GPa_to_Pa(em);
            end
            
            rho_c=c*gas_md;
            
            display_gas_acoustic_impedance(app.iu,rho_c);
            
            thick=str2num(get(handles.edit_thick,'String'));
            L=str2num(get(handles.edit_L,'String'));
            W=str2num(get(handles.edit_W,'String'));
            
            Ap=L*W;
            
            if(app.iu==2)
                thick=thick/1000;
            end
            
            mass=md*thick*L*W;
            
            rho=md;
            
            [fcr]=critical_frequency_thin_plate(c,thick,mu,rho,em);
            
            [CL]=CL_plate(em,rho,mu);
            
            rho_s=md*thick;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Thin Panel
            
            %    L = length
            %    W = width
            %    c = speed of sound
            %    fcr = critical frequency
            
            v=zeros(NL,1);
            rad_eff=zeros(NL,1);
            
            for i=1:NL
            
                freq=fc(i);
            
                [rad_eff(i)]=re_thin_plate_free(freq,fcr,L,W,c);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                omega=tpi*freq;
            
                anum=sqrt(12)*pi*c^2;
                aden=2*rho_c*thick*CL*rho_s*omega^2;
            
                bden= 1 + (rho_s*omega*lf(i))/(2*rho_c*rad_eff(i));
            
                v2=(pressure(i)^2)*(anum/aden)*(1 / bden);
            
                v(i)=sqrt(v2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                radiation_resistance=rho_c*Ap*rad_eff;
                clf=radiation_resistance/(mass*tpi*freq);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            end

            nb=get(handles.listbox_band,'Value');
            
            scf=str2num(get(handles.edit_scf,'String'));
            
            K=sqrt(3);
            strain=(K/CL)*v;
            stress=em*strain*scf;
            
            stress=fix_size(stress);
            strain=fix_size(strain);
            
            if(app.iu==2)
                v=v*1000;
            end
            
            fprintf(' Critical Frequency = %8.4g Hz \n\n',fcr);
            
            fprintf(' Stress Concentration Factor = %g \n\n',scf);
            
            disp(' Stress is Free Field ');
            disp(' ');
            
            disp(' ');
                disp(' Freq     Velocity    Stress      Radiation');
            if(app.iu==1)
                disp(' (Hz)   (in/sec rms)  (psi rms)   Efficiency');
            else
                disp(' (Hz)   (mm/sec rms)  (Pa rms)    Efficiency');
            end
            
            a=zeros(NL,1);
            
            for i=1:NL
               fprintf(' %g    %8.4g    %8.4g    %7.3g \n',fc(i),v(i),stress(i),rad_eff(i));
               a(i)=v(i)*(2*pi*fc(i));
            end
            
            if(app.iu==1)
                a=a/386;
            else
                a=a/9810;
            end
            
            arms=sqrt(sum(a.^2));
            vrms=sqrt(sum(v.^2));
            prms=sqrt(sum(stress.^2));
            
            disp(' ');
            if(app.iu==1)
                out1=sprintf(' Overall Acceleration = %7.3g GRMS',arms);
            else
                out1=sprintf(' Overall Acceleration = %7.3g m/sec^2 RMS',arms);
            end
            disp(out1);
            
            if(app.iu==1)
                out1=sprintf(' Overall Velocity = %7.3g in/sec RMS',vrms);
            else
                out1=sprintf(' Overall Velocity = %7.3g mm/sec RMS',vrms);
            end
            disp(out1);
            
            if(app.iu==1)
                out1=sprintf(' Overall Stress   = %7.3g psi RMS',prms);
            else
                out1=sprintf(' Overall Stress   = %7.3g Pa RMS',prms);
            end
            disp(out1);
            
            accel_ps=[fc a];
            vel_ps=[fc v];
            stress_ps=[fc stress];
            rad_eff=[fc rad_eff];
            
            assignin('base', 'vel_ps',vel_ps);
            assignin('base', 'stress_ps',stress_ps);
            assignin('base', 'rad_eff',rad_eff);
            
            %%%
            %
            %  plots
            %
            ngg=2;
            md=4;
            fig_num=1;
            fmin=min(fc);
            fmax=max(fc);
            x_label='Frequency (Hz)';

            if(pu==1) % dB
            
                if(nb<=2)
                    n_type=nb;
                    if(n_type~=1)
                        [fig_num]=spl_plot(fig_num,n_type,fc,dB);
                    else    
                        [fig_num]=spl_plot_bar(fig_num,fc,dB);
                    end
                end
            
            else
            
                t_string='Acoustic Pressure';
                if(app.iu==1)
                    y_label='Pressure (psi rms)';
                else
                    y_label='Pressure (Pa rms)';
                end
                ppp=[fc app.THM(:,2)];

                if(nb~=1)

                    [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                else
                    [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
                end

            end
            
            
            t_string='Panel Loss Factor';
            y_label='Loss Factor';
            ppp=[fc lf];

            if(nb~=1)
            [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
            end
            

            if(app.iu==1)
                t_string=sprintf('Panel Acceleration   %7.3g GRMS',arms);
            else
                t_string=sprintf('Panel Acceleration   %7.3g m/sec^2 RMS',arms);
            end

            y_label='Accel (GRMS)';
 
            ppp=accel_ps;
            
            if(nb~=1)
            
                [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
            end

            if(nb<=2)
            
                [psd,~]=psd_from_spectrum(nb,fc,accel_ps(:,2));
            
                disp(' ');
                disp(' Power Spectral Density ');
                disp(' ');
                disp('   Freq    Accel');
                disp('   (Hz)   (G^2/Hz)');
            
                for i=1:NL
                    fprintf('   %g   %8.4g \n',fc(i),psd(i));
                end
            
                accel_psd=[fc psd];
            
                rrr1=accel_psd;
            
                y_label='Accel (G^2/Hz)';
            
                t_string=sprintf('Power Spectral Density  %7.3g GRMS Overall',arms);
            
                [fig_num,~]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);
            
            end
            %%%%%%%%%%%%%%%%%


            if(app.iu==1)
                t_string=sprintf('Panel Velocity   %7.3g in/sec rms',vrms);
                y_label='Vel (in/sec rms)';
            else                
                t_string=sprintf('Panel Velocity   %7.3g mm/sec rms',vrms);
                y_label='Vel (mm/sec rms)';
            end
            ppp=vel_ps;

            if(nb~=1)
                [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
            end

            if(app.iu==1)
                t_string=sprintf('Panel Stress   %7.4g psi rms',prms);
                y_label='Stress (psi rms)';
            else
                t_string=sprintf('Panel Stress   %7.4g Pa rms',prms);
                y_label='Stress (Pa rms)';
            end
            ppp=stress_ps;

            if(nb~=1)
                [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
            end
            
            t_string='Radiation Efficiency';
            y_label='Radiation Efficiency';
            ppp=rad_eff;

            if(nb~=1)
                [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp,t_string,ngg);
            end
            
            %%%
            
            if(pu~=1)
               disp(' ');
            end
            
            disp(' Output Arrays ');
            disp('   Power Spectra:  vel_ps & stress_ps ');
            disp('   Radiation Efficiency:  rad_eff ');
            
            %%%
            
            msgbox('Results written to Command Window');
        end

        % Button pushed function: pushbutton_re
        function pushbutton_re_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_re (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_structure,'Value');
            
            if(n==1)
                 A = imread('re_free_panel.jpg');
                 figure(998)
                 imshow(A,'border','tight','InitialMagnification',100)
            else
                A = imread('free_panel_velox.jpg');
                figure(999)
                imshow(A,'border','tight','InitialMagnification',100)
            end
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_rr
        function pushbutton_rr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_rr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                A = imread('freely_hung_panel_diffuse_field.jpg');
                figure(997)
                imshow(A,'border','tight','InitialMagnification',100)
        end

        % Callback function
        function PinkNoiseForceSpectrumButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'freely_hung_panel_diffuse_field_multi';
            app.figure1.Tag = 'figure1';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'View Equations';
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton_re = uibutton(app.uipanel5, 'push');
            app.pushbutton_re.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_re_Callback, true);
            app.pushbutton_re.Tag = 'pushbutton_re';
            app.pushbutton_re.Text = 'Radiation Efficiency';
            app.pushbutton_rr = uibutton(app.uipanel5, 'push');
            app.pushbutton_rr.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_rr_Callback, true);
            app.pushbutton_rr.Tag = 'pushbutton_rr';
            app.pushbutton_rr.Text = 'Velocity';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'freely_hung_panel_diffuse_field_multi.m  ver 1.2  by Tom Irvine';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Enter Data';
            app.uipanel2.Tag = 'uipanel2';
            app.listbox_material = uilistbox(app.uipanel2);
            app.listbox_material.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.listbox_material.ValueChangedFcn = createCallbackFcn(app, @listbox_material_Callback, true);
            app.listbox_material.Tag = 'listbox_material';
            app.listbox_material.Value = 'Aluminum';
            app.text_material = uilabel(app.uipanel2);
            app.text_material.Tag = 'text_material';
            app.text_material.Text = 'Select Material';
            app.text_em = uilabel(app.uipanel2);
            app.text_em.Tag = 'text_em';
            app.text_em.Text = 'Elastic Modulus (psi)';
            app.edit_em = uieditfield(app.uipanel2, 'text');
            app.edit_em.Tag = 'edit_em';
            app.text_md = uilabel(app.uipanel2);
            app.text_md.Tag = 'text_md';
            app.text_md.Text = 'Mass Density (lbm/in^3)';
            app.edit_md = uieditfield(app.uipanel2, 'text');
            app.edit_md.Tag = 'edit_md';
            app.edit_mu = uieditfield(app.uipanel2, 'text');
            app.edit_mu.Tag = 'edit_mu';
            app.text_mu = uilabel(app.uipanel2);
            app.text_mu.Tag = 'text_mu';
            app.text_mu.Text = 'Poisson Ratio';
            app.listbox_units = uilistbox(app.uipanel2);
            app.listbox_units.Items = {'English', 'Metric'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'English';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Units';
            app.text35 = uilabel(app.uipanel2);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Select Structure';
            app.listbox_structure = uilistbox(app.uipanel2);
            app.listbox_structure.Items = {'Thin Panel'};
            app.listbox_structure.ValueChangedFcn = createCallbackFcn(app, @listbox_structure_Callback, true);
            app.listbox_structure.Tag = 'listbox_structure';
            app.listbox_structure.Value = 'Thin Panel';
            app.text_gas_md = uilabel(app.uipanel2);
            app.text_gas_md.Tag = 'text_gas_md';
            app.text_gas_md.Text = 'Gas Mass Density (lbm/ft^3)';
            app.edit_gas_md = uieditfield(app.uipanel2, 'text');
            app.edit_gas_md.Tag = 'edit_gas_md';
            app.edit_thick = uieditfield(app.uipanel2, 'text');
            app.edit_thick.Tag = 'edit_thick';
            app.text_thick = uilabel(app.uipanel2);
            app.text_thick.Tag = 'text_thick';
            app.text_thick.Text = 'Thickness (in)';
            app.text_gas_c = uilabel(app.uipanel2);
            app.text_gas_c.Tag = 'text_gas_c';
            app.text_gas_c.Text = 'Gas Speed of Sound (ft/sec)';
            app.edit_c = uieditfield(app.uipanel2, 'text');
            app.edit_c.Tag = 'edit_c';
            app.listbox_gas = uilistbox(app.uipanel2);
            app.listbox_gas.Items = {'Air', 'Other'};
            app.listbox_gas.ValueChangedFcn = createCallbackFcn(app, @listbox_gas_Callback, true);
            app.listbox_gas.Tag = 'listbox_gas';
            app.listbox_gas.Value = 'Air';
            app.text47 = uilabel(app.uipanel2);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Select Surrounding Gas';
            app.text_L = uilabel(app.uipanel2);
            app.text_L.Tag = 'text_L';
            app.text_L.Text = 'Length (in)';
            app.edit_L = uieditfield(app.uipanel2, 'text');
            app.edit_L.Tag = 'edit_L';
            app.text_W = uilabel(app.uipanel2);
            app.text_W.Tag = 'text_W';
            app.text_W.Text = 'Width (in)';
            app.edit_W = uieditfield(app.uipanel2, 'text');
            app.edit_W.Tag = 'edit_W';
            app.listbox_pu = uilistbox(app.uipanel2);
            app.listbox_pu.Items = {'dB ref 20 micro Pa', 'psi rms'};
            app.listbox_pu.Tag = 'listbox_pu';
            app.listbox_pu.Value = 'dB ref 20 micro Pa';
            app.text54 = uilabel(app.uipanel2);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Input Pressure Unit';
            app.text55 = uilabel(app.uipanel2);
            app.text55.Tag = 'text55';
            app.text55.Text = 'Stress Concentration Factor';
            app.edit_input_array = uieditfield(app.uipanel2, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text58 = uilabel(app.uipanel2);
            app.text58.Tag = 'text58';
            app.text58.Text = 'Frequency Spacing';
            app.listbox_band = uilistbox(app.uipanel2);
            app.listbox_band.Items = {'One-third Octave', 'Octave', 'Other'};
            app.listbox_band.Tag = 'listbox_band';
            app.listbox_band.Value = 'One-third Octave';
            app.edit_scf = uieditfield(app.uipanel2, 'text');
            app.edit_scf.Tag = 'edit_scf';
            app.edit_scf.Value = '1';
            app.EditFieldLabel = uilabel(app.uipanel2);
            app.EditFieldLabel.Text = {'Uniform Loss Factor if the Input '; 'Array only has Two Columns'};
            app.UniformLossFactor = uieditfield(app.uipanel2, 'numeric');
            app.UniformLossFactor.Value = 0.01;
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Enter Input Array Name';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = {'The input array must have at least two columns:'; '   '; '  1.  Center Frequency (Hz)     '; '  2.  Pressure   '; '  3.  Total Loss Factor (optional)'};
            app.ContextMenu = uicontextmenu(app.figure1);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = freely_hung_panel_diffuse_field_multi_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)freely_hung_panel_diffuse_field_multi_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end