classdef frf_state_space < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        frf_state_spaceUIFigure        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculateTab                   matlab.ui.container.Tab
        ViewPlotsLabel                 matlab.ui.control.Label
        AccelForceCheckBox             matlab.ui.control.CheckBox
        VelForceCheckBox               matlab.ui.control.CheckBox
        DispForceCheckBox              matlab.ui.control.CheckBox
        DArrayNamesLabel               matlab.ui.control.Label
        StiffnessEditField             matlab.ui.control.EditField
        StiffnessEditFieldLabel        matlab.ui.control.Label
        DampingCoefficientEditField    matlab.ui.control.EditField
        DampingCoefficientEditFieldLabel  matlab.ui.control.Label
        MassEditField                  matlab.ui.control.EditField
        MassEditFieldLabel             matlab.ui.control.Label
        FreqStepHzEditField            matlab.ui.control.EditField
        FreqStepHzEditFieldLabel       matlab.ui.control.Label
        MaxFreqHzEditField             matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel        matlab.ui.control.Label
        MinFreqHzEditField             matlab.ui.control.EditField
        MinFreqHzEditFieldLabel        matlab.ui.control.Label
        ResponsedofnumberEditField     matlab.ui.control.EditField
        ResponsedofnumberEditFieldLabel  matlab.ui.control.Label
        ForcedofnumberEditField        matlab.ui.control.EditField
        ForcedofnumberEditFieldLabel   matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsmassdampingstiffnessListBoxLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ExportFRFTab                   matlab.ui.container.Tab
        FRFTypeListBox                 matlab.ui.control.ListBox
        FRFTypeListBoxLabel            matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FirstcolumnisFreqHzLabel       matlab.ui.control.Label
        FormatListBox                  matlab.ui.control.ListBox
        FormatListBoxLabel             matlab.ui.control.Label
        ExportEigenvaluesVectorsTab    matlab.ui.container.Tab
        OutputArrayNamesLabel          matlab.ui.control.Label
        EigenvaluesEditField           matlab.ui.control.EditField
        EigenvaluesEditFieldLabel      matlab.ui.control.Label
        EigenvectorsEditField          matlab.ui.control.EditField
        EigenvectorsEditFieldLabel     matlab.ui.control.Label
        SaveButton_2                   matlab.ui.control.Button
        frf_state_spacever10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        freq % Description
        Hd   %
        Hv   %
        Ha   %
        lambda     %
        ModeShapes %      
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     

            app.SaveButton.Enable='off';
            app.SaveButton_2.Enable='off';

        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % calculate

            try
                FS=app.MassEditField.Value;
                M=evalin('base',FS);
            catch
                warndlg('Mass array does not exist.');
                return;
            end

            try
                FS=app.DampingCoefficientEditField.Value;
                C=evalin('base',FS);
            catch
                warndlg('Damping array does not exist.');
                return;
            end         

            try
                FS=app.StiffnessEditField.Value;
                K=evalin('base',FS);
            catch
                warndlg('Stiffness array does not exist.');
                return;
            end       

            sz=size(M);

            N=sz(1);

            dof1=str2num(app.ForcedofnumberEditField.Value);
            dof2=str2num(app.ResponsedofnumberEditField.Value);

            if(dof1>N || dof2>N)
                ss=sprintf('Max dof number = %g',N);        
                warndlg(ss)
                return;
            end

            fmax=str2num(app.MaxFreqHzEditField.Value);
            fmin=str2num(app.MinFreqHzEditField.Value);  
            df=str2num(app.FreqStepHzEditField.Value);

            num=ceil((fmax-fmin)/df);

            app.freq=fmin+(0:num-1)*df;

            if(app.freq(end)<fmax)
                app.freq(end+1)=fmax;
            end

            try
                [app.Hd,app.Hv,app.Ha,app.lambda,app.ModeShapes]=state_space_frf_a(M,C,K,app.freq,dof1,dof2);
            catch
                warndlg('Calculation failed: check input parameters');
                return;
            end

            Items = app.UnitsListBox.Items;
            im = strtrim(app.UnitsListBox.Value);
            iu = find(strcmp(Items, im));

            if(iu==1)
                app.Ha=app.Ha/386;
            end

            md=5;

            fig_num=1;
            
            if(app.DispForceCheckBox.Value)
                t_string=sprintf('Receptance H %d %d ',dof1,dof2);
                if(iu==1)
                    y_label='Disp/Force (in/lbf)';
                else
                    y_label='Disp/Force (m/N)';             
                end
                [fig_num]=plot_frf_md(fig_num,app.freq,app.Hd,fmin,fmax,t_string,y_label,md);
            end
            if(app.VelForceCheckBox.Value)
                t_string=sprintf('Mobility H %d %d ',dof1,dof2);    
                if(iu==1)
                    y_label='Vel/Force (in/sec/lbf)';
                else
                    y_label='Vel/Force (m/sec/N)';             
                end              
                [fig_num]=plot_frf_md(fig_num,app.freq,app.Hv,fmin,fmax,t_string,y_label,md);
            end            
            if(app.AccelForceCheckBox.Value)
                t_string=sprintf('Accelerance H %d %d ',dof1,dof2);
                if(iu==1)
                    y_label='Accel/Force (G/lbf)';
                else
                    y_label='Accel/Force (m/sec^2/N)';             
                end                     
                [fig_num]=plot_frf_md(fig_num,app.freq,app.Ha,fmin,fmax,t_string,y_label,md);
            end        

            app.SaveButton.Enable='on';
            app.SaveButton_2.Enable='on';


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = app.FRFTypeListBox.Items;
            im = strtrim(app.FRFTypeListBox.Value);
            nFRF = find(strcmp(Items, im));

            Items = app.FormatListBox.Items;
            im = strtrim(app.FormatListBox.Value);
            nformat = find(strcmp(Items, im));

            if(nFRF==1)
                H=app.Hd;
            end    
            if(nFRF==2)
                H=app.Hv;
            end   
            if(nFRF==3)
                H=app.Ha;
            end               
            
            H=fix_size(H);
            app.freq=fix_size(app.freq);

            if(nformat==1)
                data=[app.freq abs(H)];
            end
            if(nformat==2)
                data=[app.freq abs(H) angle(H)*180/pi];            
            end
            if(nformat==3)
                data=[app.freq H];            
            end

            n1=app.OutputArrayNameEditField.Value;

            try
                assignin('base',n1,data);          
                msgbox('Array saved');
            catch
                warndlg('Save failed');
            end

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            
            ClosePlots(app);

        end

        % Value changing function: ForcedofnumberEditField
        function ForcedofnumberEditFieldValueChanging(app, event)
            ClosePlots(app);
            
        end

        % Value changed function: ResponsedofnumberEditField
        function ResponsedofnumberEditFieldValueChanged(app, event)
            ClosePlots(app);
            
        end

        % Value changing function: MassEditField
        function MassEditFieldValueChanging(app, event)
            ClosePlots(app);
            
        end

        % Value changing function: DampingCoefficientEditField
        function DampingCoefficientEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: StiffnessEditField
        function StiffnessEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: FreqStepHzEditField
        function FreqStepHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxFreqHzEditField
        function MaxFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinFreqHzEditField
        function MinFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
         
            n1=app.EigenvaluesEditField.Value;
            n2=app.EigenvectorsEditField.Value;

            try
                assignin('base',n1,app.lambda);  
                assignin('base',n2,app.ModeShapes);            
                msgbox('Arrays saved');
            catch
                warndlg('Save failed');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.frf_state_spaceUIFigure = uifigure('Visible', 'off');
            app.frf_state_spaceUIFigure.Name = 'frf_state_space';
            app.ReturnButton = uibutton(app.frf_state_spaceUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.frf_state_spacever10byTomIrvineLabel = uilabel(app.frf_state_spaceUIFigure);
            app.frf_state_spacever10byTomIrvineLabel.Text = 'frf_state_space   ver 1.0   by Tom Irvine        Frequency Response Functions for Systems for Systems with Dashpot Damping';
            app.TabGroup = uitabgroup(app.frf_state_spaceUIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UnitsmassdampingstiffnessListBoxLabel = uilabel(app.CalculateTab);
            app.UnitsmassdampingstiffnessListBoxLabel.Text = 'Units:  mass, damping, stiffness';
            app.UnitsListBox = uilistbox(app.CalculateTab);
            app.UnitsListBox.Items = {'lbf sec^2/in, lbf sec/in, lbf/in', 'kg, N sec/m, N/m'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'lbf sec^2/in, lbf sec/in, lbf/in';
            app.ForcedofnumberEditFieldLabel = uilabel(app.CalculateTab);
            app.ForcedofnumberEditFieldLabel.Text = 'Force dof number';
            app.ForcedofnumberEditField = uieditfield(app.CalculateTab, 'text');
            app.ForcedofnumberEditField.ValueChangingFcn = createCallbackFcn(app, @ForcedofnumberEditFieldValueChanging, true);
            app.ResponsedofnumberEditFieldLabel = uilabel(app.CalculateTab);
            app.ResponsedofnumberEditFieldLabel.Text = 'Response dof number';
            app.ResponsedofnumberEditField = uieditfield(app.CalculateTab, 'text');
            app.ResponsedofnumberEditField.ValueChangedFcn = createCallbackFcn(app, @ResponsedofnumberEditFieldValueChanged, true);
            app.MinFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.MinFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MinFreqHzEditFieldValueChanging, true);
            app.MaxFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.MaxFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MaxFreqHzEditFieldValueChanging, true);
            app.FreqStepHzEditFieldLabel = uilabel(app.CalculateTab);
            app.FreqStepHzEditFieldLabel.Text = 'Freq Step (Hz)';
            app.FreqStepHzEditField = uieditfield(app.CalculateTab, 'text');
            app.FreqStepHzEditField.ValueChangingFcn = createCallbackFcn(app, @FreqStepHzEditFieldValueChanging, true);
            app.MassEditFieldLabel = uilabel(app.CalculateTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.CalculateTab, 'text');
            app.MassEditField.ValueChangingFcn = createCallbackFcn(app, @MassEditFieldValueChanging, true);
            app.DampingCoefficientEditFieldLabel = uilabel(app.CalculateTab);
            app.DampingCoefficientEditFieldLabel.Text = 'Damping Coefficient';
            app.DampingCoefficientEditField = uieditfield(app.CalculateTab, 'text');
            app.DampingCoefficientEditField.ValueChangingFcn = createCallbackFcn(app, @DampingCoefficientEditFieldValueChanging, true);
            app.StiffnessEditFieldLabel = uilabel(app.CalculateTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.CalculateTab, 'text');
            app.StiffnessEditField.ValueChangingFcn = createCallbackFcn(app, @StiffnessEditFieldValueChanging, true);
            app.DArrayNamesLabel = uilabel(app.CalculateTab);
            app.DArrayNamesLabel.Text = '** 2D Array Names ** ';
            app.DispForceCheckBox = uicheckbox(app.CalculateTab);
            app.DispForceCheckBox.Text = 'Disp/Force';
            app.DispForceCheckBox.Value = true;
            app.VelForceCheckBox = uicheckbox(app.CalculateTab);
            app.VelForceCheckBox.Text = 'Vel/Force';
            app.AccelForceCheckBox = uicheckbox(app.CalculateTab);
            app.AccelForceCheckBox.Text = 'Accel/Force';
            app.ViewPlotsLabel = uilabel(app.CalculateTab);
            app.ViewPlotsLabel.Text = '** View Plots ** ';
            app.ExportFRFTab = uitab(app.TabGroup);
            app.ExportFRFTab.Title = 'Export FRF';
            app.FormatListBoxLabel = uilabel(app.ExportFRFTab);
            app.FormatListBoxLabel.Text = 'Format';
            app.FormatListBox = uilistbox(app.ExportFRFTab);
            app.FormatListBox.Items = {'Magnitude', 'Magnitude & Phase', 'Complex'};
            app.FormatListBox.Value = 'Magnitude';
            app.FirstcolumnisFreqHzLabel = uilabel(app.ExportFRFTab);
            app.FirstcolumnisFreqHzLabel.Text = 'First column is Freq (Hz)';
            app.SaveButton = uibutton(app.ExportFRFTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportFRFTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportFRFTab, 'text');
            app.FRFTypeListBoxLabel = uilabel(app.ExportFRFTab);
            app.FRFTypeListBoxLabel.Text = 'FRF Type';
            app.FRFTypeListBox = uilistbox(app.ExportFRFTab);
            app.FRFTypeListBox.Items = {'displacement/force', 'velocity/force', 'acceleration/force'};
            app.FRFTypeListBox.Value = 'displacement/force';
            app.ExportEigenvaluesVectorsTab = uitab(app.TabGroup);
            app.ExportEigenvaluesVectorsTab.Title = 'Export Eigenvalues & Vectors';
            app.SaveButton_2 = uibutton(app.ExportEigenvaluesVectorsTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.EigenvectorsEditFieldLabel = uilabel(app.ExportEigenvaluesVectorsTab);
            app.EigenvectorsEditFieldLabel.Text = 'Eigenvectors';
            app.EigenvectorsEditField = uieditfield(app.ExportEigenvaluesVectorsTab, 'text');
            app.EigenvectorsEditField.Value = 'eigenvectors';
            app.EigenvaluesEditFieldLabel = uilabel(app.ExportEigenvaluesVectorsTab);
            app.EigenvaluesEditFieldLabel.Text = 'Eigenvalues';
            app.EigenvaluesEditField = uieditfield(app.ExportEigenvaluesVectorsTab, 'text');
            app.EigenvaluesEditField.Value = 'eigenvalues';
            app.OutputArrayNamesLabel = uilabel(app.ExportEigenvaluesVectorsTab);
            app.OutputArrayNamesLabel.Text = '** Output Array Names ** ';
            % Show the figure after all components are created
            app.frf_state_spaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frf_state_space

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.frf_state_spaceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.frf_state_spaceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.frf_state_spaceUIFigure)
        end
    end
end