classdef frgsdfgsfd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIBeamBendingFEAUIFigure        matlab.ui.Figure
        beam_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        NumberofElementsListBox         matlab.ui.control.ListBox
        NumberofElementsListBoxLabel    matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        ImageIBeam                      matlab.ui.control.Image
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.NumericEditField
        EditField_1Label                matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        LengthinEditField               matlab.ui.control.NumericEditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        MaterialAddedMassTab            matlab.ui.container.Tab
        PointMassLocationfromLeftEndinEditField  matlab.ui.control.NumericEditField
        PointMassLocationfromLeftEndinEditFieldLabel  matlab.ui.control.Label
        PointMasslbmEditField           matlab.ui.control.NumericEditField
        PointMasslbmEditFieldLabel      matlab.ui.control.Label
        NumberofPointMassesListBox      matlab.ui.control.ListBox
        NumberofPointMassesListBoxLabel  matlab.ui.control.Label
        UniformNSMEditField             matlab.ui.control.NumericEditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialLabel                   matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.NumericEditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.NumericEditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        NaturalFrequenciesTab           matlab.ui.container.Tab
        CalculateNaturalFrequenciesPartitionButton  matlab.ui.control.Button
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        PlotModeShapeButton             matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        BaseExcitationResponseTab       matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputFunctionListBox           matlab.ui.control.ListBox
        OutputFunctionListBoxLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ImageBeamBase                   matlab.ui.control.Image
        NumberofModesListBox            matlab.ui.control.ListBox
        NumberofModesListBox_3Label_3   matlab.ui.control.Label
        TabGroupBase                    matlab.ui.container.TabGroup
        TransTab                        matlab.ui.container.Tab
        CalculateBaseInputTrans         matlab.ui.control.Button
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        EndFrequencyHzEditField_2Label  matlab.ui.control.Label
        StartFrequencyHzEditField       matlab.ui.control.NumericEditField
        StartFrequencyHzEditField_2Label  matlab.ui.control.Label
        SineTab                         matlab.ui.container.Tab
        ExcitationFreqHzEditField       matlab.ui.control.EditField
        ExcitationFreqHzEditFieldLabel  matlab.ui.control.Label
        BaseAccelerationGEditField      matlab.ui.control.EditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        CalculateBaseInputSine          matlab.ui.control.Button
        SettoFirstfnButton              matlab.ui.control.Button
        HalfSineTab                     matlab.ui.container.Tab
        CalculateResponseButton         matlab.ui.control.Button
        TotalResponseDurationsecEditField_3  matlab.ui.control.EditField
        TotalResponseDurationsecEditField_3Label  matlab.ui.control.Label
        PulseDurationsecEditField_3     matlab.ui.control.EditField
        PulseDurationsecEditField_3Label  matlab.ui.control.Label
        BaseInputAccelGEditField_3      matlab.ui.control.EditField
        BaseInputAccelGEditField_3Label  matlab.ui.control.Label
        PSDTab                          matlab.ui.container.Tab
        UpdateListButton_psd            matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculateBaseInputPSD_2         matlab.ui.control.Button
        ArbitraryTab                    matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateBaseInputArbitrary_2   matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecaccelGLabel_2  matlab.ui.control.Label
        MDOFSRSTab                      matlab.ui.container.Tab
        CalculateBaseInputMDOFSRS_2     matlab.ui.control.Button
        InputArrayNameEditField_MDOFSRS  matlab.ui.control.EditField
        InputArrayNameEditField_2Label_4  matlab.ui.control.Label
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        ResponseLocationListBox         matlab.ui.control.ListBox
        ResponseLocationinchfromLeftEndLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        L   % Length
        E   % Eelastic modulus
        rho % Mass density
        fn  % Natural frequencies
        dm  % Damping
        omegan % Angular natural frequencies
        ModeShapes % Mode Shapes
        MST  % Mode Shapes Transpose
        cna  % Distance to neutral axis
        x    % x length along beam for mode shapes
        y    % Mode shape
        xx   % x length along beam for mode shapes
        ZZ   % Mode shape
        sq_mass % sqrt of mass
        MOI % Area moment of intertia
        C   % Modal constant
        beta % eigenvalue parameter
        part % participation factor
        s1  % modal frequency 1
        s2  % modal frequency 2
        s3  % modal frequency 3
        s4  % modal frequency 4
        s5  % modal frequency 5
        s6  % modal frequency 6
        RBC % Right boundary condition
        LBC % Left boundary condition
        BC  % Boundary condition
        bending_stress % 
        shear_force    % 
        bending_moment % 
        absolute_accel % 
        relative_vel   % 
        relative_disp  %   
        acceleration   %
        relative_velocity     %
        relative_displacement %   
        acc_response_psd %
	    rv_response_psd  %
        rd_response_psd  %
        acc_response_trans    %
        rd_response_trans     %        
        moment_response_psd   %            
        shear_response_psd    %
        stress_response_psd   %
        stress_response_trans %        
        atrans  %
        rdtrans %
        strans  %

        global_stiff     %
        global_mass      %
        global_stiff_unc %
        global_mass_unc  %   
        
        dof % degrees-of-freedom
        iu % iu=1 for English unapp.Kwwits
        ne % number of elements
        dof_status % dof status
        dx % element length array;
        tdof % translational dof
        
        pomegan  %
        pomn2    %
        pModeShapes %
        pMST     %
        pdof     %
        response_dof  %
        Mww  %
        Mwd  %
        Kww  %
        ngw  %
        nff  %
        nem  %
        num  %
        TT   %
        na   %
        iflag %
        kflag %
        xxr   %
        rs    %
        np    %
        adisp %
        HM_stress_response %
        number_modes      %
        nnn      %
        nnn_max  %
        nde  %
        acc  %
        ddd  %
        stress %
        nt   %
        THM  %
        dt   %
        ddisp %
    end
    
    methods (Access = private)

       function update_th(app)
            CloseFigures(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end

        function PreProcess(app)
            CloseFigures(app);
            ResponseX(app);
                    
            app.kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                app.kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                app.kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                app.kflag=1;
            end 
            if(app.kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end        
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end     
            
        end    

        function update_psd(app)
            CloseFigures(app);
            [cell_array]=update_power_spectral_density();
           
            try
                app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            catch
            end
        end          

        function beam_FEA_base_arbitrary(app)

            disp(' arb ')

            clear app.na;
            clear app.acc;
            clear app.ddd;
            clear app.nt;
            clear app.acceleration;
            clear app.relative_disp;          
            clear app.stress;

            Items = char(app.ResponseLocationListBox.Items);
            im=app.ResponseLocationListBox.Value;
            [app.nnn]=find_index(Items,im);          

            app.xxr=im;
            app.response_dof=2*app.nnn-1;

            fig_num=1;

            NT=length(app.THM(:,1));
            app.nt=NT;

            EA=app.THM;
            
            dur=EA(NT,1)-EA(1,1);
            app.dt=dur/(NT-1);
            
            sr=1/app.dt;
            
            fprintf(' NT=%d  dur=%8.4g sec  app.dt=%8.4g sec \n',NT,dur,app.dt);  

            fprintf('\n number of modes included =%d \n',app.number_modes);
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integrating acceleration ');
            
            ndof=app.pdof;
            
            f=app.THM(:,2);
            
            if(app.BC==1)
                fin=f;
            else
                fin=[ f f]; 
            end
            
            [dvelox]=integrate_function(f,app.dt);
            [app.ddisp]=integrate_function(dvelox,app.dt);
            %
            %  Modal Transient
            %
            clear omegad;
            omegad=zeros(app.nff,1);

            for i=1:length(app.dm)
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
            end
            
            noo=app.number_modes;
        
            for i=1:noo
                
                if(sr<10*app.fn(i))
                   app.number_modes=i-1;
                   fprintf('\n Number of included modes = %d due to input data sample rate limitation. \n',app.number_modes);         
                   break; 
                end
                
            end

            fprintf('\n sample rate = %8.4g samples/sec \n',sr);

            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:app.number_modes 
               fprintf(' %d.  %8.3g  %6.3g \n',i,app.fn(i),app.dm(i)); 
            end
            
            Q=app.ModeShapes(:,1:app.number_modes);
            QT=Q';

            ff=zeros(app.nem,1);

            progressbar;

            progressbar(0.05);

            QTapp.Mwd=QT*app.Mwd;
            
            for i=1:length(f)
                ff(1:app.nem,i)=f(i);
            end

            progressbar(0.1);
            
            disp(' ');
            disp(' Calculating FP ');
        
            FP=-QTapp.Mwd*ff;
            
            disp(' Completed FP ')    

            [a1,a2,df1,df2,df3,~,~,~,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(app.number_modes,app.omegan,app.dm,app.dt);

            nx=zeros(app.nt,app.number_modes);
            app.na=zeros(app.nt,app.number_modes);
        
            for j=1:app.number_modes
            %
                amodal=FP(j,:);
            %
            %  displacement
            %
                d_forward=[   df1(j),  df2(j), df3(j) ];
                d_back   =[     1, -a1(j), -a2(j) ];
                d_resp=filter(d_forward,d_back,amodal);
            %       
            %  acceleration
            %   
                a_forward=[   af1(j),  af2(j), af3(j) ];
                a_back   =[     1, -a1(j), -a2(j) ]; 
                a_resp=filter(a_forward,a_back,amodal);
            %
                nx(:,j)=d_resp;  % displacement
                app.na(:,j)=a_resp;  % acceleration  
            %
            end

            progressbar(0.25);

            %
            clear amodal;
            clear FP;
            clear d_resp;
            clear a_resp;
            
            disp(' Calculate a');
            
            d=zeros((ndof-app.nem),app.nt);
            d(:,:)=(Q*(nx(:,:))');
            clear nx;

            progressbar(0.3);
            
            disp(' Calculate d');
            a=zeros((ndof-app.nem),app.nt);
            a(:,:)=(Q*(app.na(:,:))'); 
            clear na;

            progressbar(0.4);
 
            %%%%

            d=d';
            a=a';
            
            fin=fix_size(fin);
            
            disp(' Transformation a  ');
            
            Adw=[fin a];
            
            sz=size(Adw);
            mqq=sz(2);
            
            A=zeros(app.nt,mqq);
            D=zeros(app.nt,mqq);

            A(:,:)=(app.TT*Adw(:,:)')';
            clear Adw;

            progressbar(0.5);
            
            disp(' Transformation d ');

            if(app.BC==1)
                Ddw=[app.ddisp d];
            else
                Ddw=[app.ddisp app.ddisp d];    
            end    
            
            D(:,:)=(app.TT*Ddw(:,:)')';
            clear Ddw;

            progressbar(0.6);

            app.acc=zeros(app.nt,mqq);
            app.ddd=zeros(app.nt,mqq);
            
            disp(' Sort  A'); 
            app.acc(:,app.ngw(:))=A(:,:);
            clear A;
            
            disp(' Sort  D'); 
            app.ddd(:,app.ngw(:))=D(:,:);
            clear D;

            progressbar(0.7);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Generate complete vectors ');
            
            zz=zeros(app.nt,1);
            
            sz=size(app.acc);
            nmn=sz(2);
            
            app.acc=[ app.acc(:,1) zz app.acc(:,2:nmn)];
            app.ddd=[ app.ddisp zz app.ddd(:,2:nmn)];

            if(app.iu==1)
               app.ddd=app.ddd*386;
               app.ddisp=app.ddisp*386;
            else
               app.ddd=app.ddd*9.81;
               app.ddisp=app.ddisp*386;
            end    
            
            progressbar(0.75);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' Integration ');

            size(app.acc)
            app.response_dof

            aaa=app.acc(:,app.response_dof);
            rrr=app.ddd(:,app.response_dof)-app.ddisp;

            app.acceleration=[app.THM(:,1),aaa];
            app.relative_disp=[app.THM(:,1),rrr];

            progressbar(0.8);
     
            Stress_th(app);

            progressbar(0.9);

            app.stress=app.HM_stress_response/1000;

            app.stress=fix_size(app.stress);

            try
                app.bending_stress=[app.THM(:,1),app.stress];
            catch
                size(app.THM(:,1))
                size(app.stress)
            end
            
            bstress=app.stress;

            amin=min(aaa);
            amax=max(aaa);         
            arms=std(aaa);   

            srdmin=min(rrr);
            srdmax=max(rrr);         
            srd=std(rrr);  

            stmin=min(bstress);
            stmax=max(bstress);         
            stss=std(bstress);              

            ss1=sprintf('Accel (G) \n  max=%7.3g  min=%7.3g  std dev=%7.3g\n\n',amax,amin,arms);
            ss2=sprintf('Rel Disp (in) \n  max=%6.2g  min=%6.2g  std dev=%6.2g\n\n',srdmax,srdmin,srd);
            ss3=sprintf('Stress (ksi) \n  max=%7.3g  min=%7.3g  std dev=%7.3g\n\n',stmax,stmin,stss);

            app.ResultsTextArea.Value=sprintf('%s %s %s',ss1,ss2,ss3);

            progressbar(1);

            string_th{1}='Acceleration';
            string_th{2}='Relative Disp';
            string_th{3}='Stress';           

            app.OutputFunctionListBox.Items=string_th; 

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),aaa);
            grid on
            ylabel('Accel (G)');
            xlabel('Time (sec)');
            legend('Input','Response');
            t_string=sprintf('Accleration  x=%s inch',app.xxr);
            title(t_string);

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),aaa);
            grid on
            ylabel('Accel (G)');
            xlabel('Time (sec)');
            t_string=sprintf('Accleration Response  x=%s inch',app.xxr);
            title(t_string); 

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),rrr);
            grid on
            ylabel('Rel Disp (in)');
            xlabel('Time (sec)');
            t_string=sprintf('Relative Displacement  x=%s inch',app.xxr);
            title(t_string);     

            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),bstress);
            grid on
            ylabel('Stress (ksi)');
            xlabel('Time (sec)');
            t_string=sprintf('Bending Stress  x=%s inch',app.xxr);
            title(t_string);            

        end    

        function Stress(app)

            app.HM_stress_response=zeros(app.np,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % 1  Fixed-Free
            % 2  Pinned-Pinned
            % 3  Fixed-Fixed
            % 4  Fixed-Pinned

            app.nnn_max=app.ne+1; 

            pL=mean(app.dx);
    
            px=0;
            [B0]=beam_stress_B(px,pL);

            if(app.nnn==1)

                if(app.BC==1 || app.BC==3 || app.BC==4)     

                    for j=1:app.np

                        Y(1)=app.adisp(j,1);
                        Y(2)=0;
                        Y(3)=app.adisp(j,2);
                        Y(4)=app.adisp(j,3);

                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B0*d);                 
                    end
                end 
            end

            if(app.nnn>=2 && app.nnn<=(app.nnn_max-2))

                L1=app.response_dof;
                L2=app.response_dof+1;    
                L3=app.response_dof+2;
                L4=app.response_dof+3; 

                for j=1:app.np
                    
                    Y(1)=app.adisp(j,L1);
                    Y(2)=app.adisp(j,L2);
                    Y(3)=app.adisp(j,L3);
                    Y(4)=app.adisp(j,L4);
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                    app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B0*d);
                
                end
            end

            if(app.nnn==app.nnn_max-1)       

                if(app.BC==3)
                    L1=app.response_dof;
                    L2=app.response_dof+1;    
                    L3=app.response_dof+2;

                    for j=1:app.np          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=0; 
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B0*d);
                    end     

                else
                    L1=app.response_dof;
                    L2=app.response_dof+1;    
                    L3=app.response_dof+2;
                    L4=app.response_dof+3;  
                    
                    for j=1:app.np          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=app.adisp(j,L4);
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B0*d);
                    end                   
                end    

            end    
            if(app.nnn==app.nnn_max)
                px=pL;
                [B]=beam_stress_B(px,pL);            

                if(app.BC==3)
                    L1=app.response_dof-2;
                    L2=app.response_dof-1;    
                    L3=app.response_dof;

                    for j=1:app.np          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=0;
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B*d);
                    end     

                else
                    L1=app.response_dof-2;
                    L2=app.response_dof-1;    
                    L3=app.response_dof;
                    L4=app.response_dof+1;  
                    
                    for j=1:app.np          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=app.adisp(j,L4);
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B*d);
                    end                   
                end    
            end    
            
        end    

        function Stress_th(app)

            ntt=app.nt;
            app.HM_stress_response=zeros(ntt,1);

            app.adisp=app.ddd;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % 1  Fixed-Free
            % 2  Pinned-Pinned
            % 3  Fixed-Fixed
            % 4  Fixed-Pinned

            disp('green')

            app.nnn_max=app.ne+1; 
            pL=mean(app.dx);
            px=0;
            [B0]=beam_stress_B(px,pL);
            
            sz=size(app.adisp);

            for i=1:sz(1)
                for j=1:2:sz(2)
                    app.adisp(i,j)=app.adisp(i,j)-app.ddd(i);
                end
            end    
          

            if(app.nnn==1)

                if(app.BC==1 || app.BC==3 || app.BC==4)     

                    for j=1:ntt

                        Y(1)=app.adisp(j,1);
                        Y(2)=app.adisp(j,2);
                        Y(3)=app.adisp(j,3);
                        Y(4)=app.adisp(j,4);

                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B0*d);                 
                    end
                end 
            end

            if(app.nnn>=2 && app.nnn<=(app.nnn_max-2))

                L1=app.response_dof;
                L2=app.response_dof+1;    
                L3=app.response_dof+2;
                L4=app.response_dof+3; 

                for j=1:ntt
                    
                    Y(1)=app.adisp(j,L1);
                    Y(2)=app.adisp(j,L2);
                    Y(3)=app.adisp(j,L3);
                    Y(4)=app.adisp(j,L4);
                    d=[Y(1)  Y(2) Y(3) Y(4) ]';
                    app.HM_stress_response(j)=(app.cna*app.E*B0*d);
                
                end
            end

            if(app.nnn==app.nnn_max-1)       

                if(app.BC==3)
                    L1=app.response_dof;
                    L2=app.response_dof+1;    
                    L3=app.response_dof+2;

                    for j=1:ntt          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=0; 
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B0*d);
                    end     

                else
                    L1=app.response_dof;
                    L2=app.response_dof+1;    
                    L3=app.response_dof+2;
                    L4=app.response_dof+3;  
                    
                    for j=1:app.np          
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=app.adisp(j,L4);
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B0*d);
                    end                   
                end    

            end    
            if(app.nnn==app.nnn_max)
                px=pL;
                [B]=beam_stress_B(px,pL);            

                if(app.BC==3)
                    L1=app.response_dof-2;
                    L2=app.response_dof-1;    
                    L3=app.response_dof;

                    for j=1:ntt         
                        Y(1)=app.adisp(j,L1);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3);
                        Y(4)=0;
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B*d);
                    end     

                else          

                    L1=app.response_dof-2;
                    L2=app.response_dof-1;    
                    L3=app.response_dof;
                    L4=app.response_dof+1; 
                    
                    for j=1:ntt          
                        Y(1)=app.adisp(j,L1)-app.ddisp(j);
                        Y(2)=app.adisp(j,L2);
                        Y(3)=app.adisp(j,L3)-app.ddisp(j);
                        Y(4)=app.adisp(j,L4);
                        d=[Y(1)  Y(2) Y(3) Y(4) ]';
                        app.HM_stress_response(j)=(app.cna*app.E*B*d);
                    end                   
                end    
            end    

        end    

        function ResponseX(app)

            disp('X')

            app.number_modes=str2num(app.NumberofModesListBox.Value);

            Lfn=length(app.fn);

            if(app.number_modes>Lfn)
                app.number_modes=Lfn;
            end    

            try
                app.xxr=app.ResponseLocationListBox.Value;  
                app.xxr=strtrim(app.xxr);
            catch
                warndlg('app.xxr failed');
                app.kflag=999;
                return; 
            end

            Items = char(app.ResponseLocationListBox.Items);
            im=app.ResponseLocationListBox.Value;
            [app.nnn]=find_index(Items,im);

            try
                BeamBaseImage(app);
                app.response_dof=app.tdof(app.nnn);
            catch
                fprintf(' app.nnn=%d  app.ne=%d \n',app.nnn,app.ne);
                app.tdof
                warndlg('app.tdof error');
                return;
            end         

            app.kflag=0;
            
            if(app.LBC==1 && app.RBC==3)
                app.kflag=1;
            end
            if(app.LBC==2 && app.RBC==2)
                app.kflag=1;
            end
            if(app.LBC==1 && app.RBC==1)
                app.kflag=1;
            end
            if(app.LBC==1 && app.RBC==2) % fixed-pinned
                app.kflag=1;
            end

            if(app.iu==1)
               app.rs=386; 
            else
               app.rs=9.81; 
            end
        end

        function PostOff(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.OutputFunctionListBox.Visible='off';
            app.OutputFunctionListBoxLabel.Visible='off';
            app.SaveButton.Visible='off';
            app.OutputArrayNameEditField.Visible='off';
            app.OutputArrayNameEditFieldLabel.Visible='off';
        end   

        function PostOn(app)
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';       
            app.OutputFunctionListBox.Visible='on';
            app.OutputFunctionListBoxLabel.Visible='on';
            app.SaveButton.Visible='on';
            app.OutputArrayNameEditField.Visible='on';
            app.OutputArrayNameEditFieldLabel.Visible='on';
        end    

        function CloseFigures(app)

            PostOff(app);
            
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
        
        function ChangeMaterials(app)
            value = app.MaterialListBox.Value;
            
            app.E=0;
            app.rho=0;            
            
            if(contains(value,'Aluminum'))
                app.E=1e+007;
                app.rho=0.1;                  
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Steel'))
                app.E=3e+007;
                app.rho= 0.28;               
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Copper'))
                app.E=1.6e+007;
                app.rho=  0.322;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'G10'))
                app.E=2.7e+006;
                app.rho=  0.065;                
            end
            value = app.MaterialListBox.Value;
            if(contains(value,'Titanium'))
                app.E=1.6e+07;           
                app.rho=0.163;                
            end
             value = app.MaterialListBox.Value;
            if(contains(value,'Graphite/Epoxy'))
                app.E=1e+007;
                app.rho=0.058;                           
            end        
            app.ElasticModuluspsiEditField.Value=app.E;
            app.MassDensitylbmin3EditField.Value=app.rho;        
            
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';

        end
        
        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end
        
        function ChangeCrossSection(app)
            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                app.EditField_1Label.Text='Width (in)';
                app.EditField_2Label.Text='Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                
            end
            if(contains(n,'Pipe'))
                app.EditField_1Label.Text='Outer Diameter (in)';
                app.EditField_2Label.Text='Wall Thickness (in)';  
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 app.EditField_1Label.Text='Diameter (in)';
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                app.EditField_1Label.Text='Base Width (in)';
                app.EditField_2Label.Text='Total Height (in)';
                app.EditField_3Label.Text='Flange Thickness (in)';     
                app.EditField_4Label.Text='Web Thickness (in)';  
                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                app.EditField_1Label.Text='Area (in^2)';
                app.EditField_2Label.Text='Area moment Inertia (in^4)';
                app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end
        
        function BeamBaseImage(app)
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;  
            
            num_nodes=app.ne+1;
       
            for i=1:num_nodes
                app.tdof(i)=(2*i-1);
            end    
                        
            if(contains(nL,'Fixed') && contains(nR,'Free'))                
                app.ImageBeamBase.ImageSource='beam_bending_base.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=1;
            end
            if(contains(nL,'Pinned') && contains(nR,'Pinned'))              
                app.ImageBeamBase.ImageSource='beam_bending_base_ss.jpg'; 
                app.BC=2;
            end   
            if(contains(nL,'Fixed') && contains(nR,'Fixed'))             
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=3;
            end
            
            if(contains(nL,'Fixed') && contains(nR,'Pinned'))                 
                app.ImageBeamBase.ImageSource='beam_bending_base_fixed.jpg';
                app.tdof(2:end)=app.tdof(2:end)-1;
                app.BC=4;
            end    

            try
                ss1=sprintf('Acceleration Transmissibilty');
                ss2=sprintf('Relative Displacement Transmissibility');
                ss3=sprintf('Bending Stress');                   
                app.OutputFunctionListBox_Trans.Items={ss1,ss2,ss3};
            catch   
            end

            try
                string_th{1}=sprintf('Acceleration Transmissibilty');
                string_th{2}=sprintf('Acceleration PSD');
                string_th{3}=sprintf('Relative Displacement Transmissibility');  
                string_th{4}=sprintf('Relative Displacement PSD');
                string_th{5}=sprintf('Bending Stress Transmissibilty');
                string_th{6}=sprintf('Bending Stress PSD');                
                app.OutputFunctionListBox_PSD.Items=string_th;
            catch       
            end
            
            clear string_th;
            
            try
                string_th{1}=sprintf('Absolute Acceleration');
                string_th{2}=sprintf('Relative Displacement');
                string_th{3}=sprintf('Bending Stress');                
                app.OutputFunctionListBox_arbitrary.Items=string_th;
                app.OutputFunctionListBox_HS.Items=string_th;
            catch       
            end             
            
        end
        
        function PointMass(app)
        
            n=str2num(app.NumberofPointMassesListBox.Value);
        
            app.PointMasslbmEditField.Visible='off';
            app.PointMassLocationfromLeftEndinEditField.Visible='off';
            app.PointMasslbmEditFieldLabel.Visible='off';
            app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='off';
            
            if(n==1)
                app.PointMasslbmEditField.Visible='on';
                app.PointMassLocationfromLeftEndinEditField.Visible='on';
                app.PointMasslbmEditFieldLabel.Visible='on';
                app.PointMassLocationfromLeftEndinEditFieldLabel.Visible='on';                 
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_psd(app);
            update_th(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            ChangeCrossSection(app);
            BeamBaseImage(app);
            PointMass(app);
            app.iu=1;
            app.na='999';
            
            for t = 1:numel(app.TabGroup.Children)
                app.TabGroup.Children(t).Tooltip = app.TabGroup.Children(t).Title;
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ChangeMaterials(app);
        end

        % Callback function
        function CalculateBaseInputTransButtonPushed(app, event)
    
        end

        % Callback function
        function CalculateBaseInputSineButtonPushed(app, event)
                           
        end

        % Callback function
        function CalculateAppliedForceLengthFRFButtonPushed(app, event)
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end            
           
        end

        % Button pushed function: 
        % CalculateNaturalFrequenciesPartitionButton
        function CalculateNaturalFrequenciesPartitionButtonPushed(app, event)
            
            CloseFigures(app);
        
            fprintf('\n * * * * * * * * * \n\n');
        
            app.L=app.LengthinEditField.Value;
            app.E=app.ElasticModuluspsiEditField.Value;
            app.rho=app.MassDensitylbmin3EditField.Value;
       
            app.rho=app.rho/386;
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=3;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=3;                
            end    
            
            if((app.LBC==2 && app.RBC==3) || (app.LBC==3 && app.RBC==2)) % pinned-free
                warndlg('case unavailable');
                return;
            end
    
            %%%%%%%%%%%%%%%%%%%%%
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=app.EditField_1.Value;
                thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=app.EditField_1.Value;
                wall_thick=app.EditField_2.Value;
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=app.EditField_1.Value;
                [area,app.MOI,app.cna]=cylinder_geometry(diameter);     
            end
            if(contains(n,'I-Beam'))
                B=app.EditField_1Label.Value;
                H=app.EditField_2Label.Value;
                Tf=app.EditField_3Label.Value;     
                Tw=app.EditField_4Label.Value;  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=app.EditField_1.Value;
                app.MOI=app.EditField_2.Value;
                app.cna=app.EditField_3.Value;       
            end
            
            nsm=app.UniformNSMEditField.Value;
            nsm=nsm/386;
            
            rho_mpL=app.rho*area + nsm/app.L;   % mass per unit length
            mass=rho_mpL*app.L;
            app.sq_mass=sqrt(mass);

            fprintf('\n area=%8.4g in^2  \t mass=%8.4g lbf sec^2/in \t MOI=%8.4g in^4 \n\n',area,mass,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%
            
            nsn=str2num(app.NumberofElementsListBox.Value);
            app.ne=nsn;

            deltax=app.L/app.ne;
            
            app.dx=zeros(app.ne,1);
            
            for i=1:app.ne
                app.dx(i)=deltax;
            end
            
            app.xx=zeros((app.ne+1),1);
            
            string_n{1}=sprintf('%8.4g',app.xx(1));
            for i=2:(app.ne+1)
               app.xx(i)=app.xx(i-1)+deltax; 
               string_n{i}=sprintf('%8.4g',app.xx(i));
            end

            app.ResponseLocationListBox.Items

            app.ResponseLocationListBox.Items=string_n;
            app.ResponseLocationListBox.Value=app.ResponseLocationListBox.Items(1);
            
            app.dof=2*app.ne+2;
   
            [klocal] = local_stiffness(app.E,app.MOI,app.ne,app.dx);
            [mlocal] = local_mass(rho_mpL,app.ne,app.dx);
            
            kk=zeros(4,4);
            mm=zeros(4,4);
            
            for i=1:4
                for j=1:4
                    kk(i,j)=klocal(1,i,j);
                    mm(i,j)=mlocal(1,i,j);
                end
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                      
            npm=str2num(app.NumberofPointMassesListBox.Value);
            
            pmloc=zeros(1,1);
            pm=zeros(1,1);
            
            if(npm==1)
                   pm(1)=app.PointMasslbmEditField.Value;
                pmloc(1)=app.PointMassLocationfromLeftEndinEditField.Value;
            end
            
            if(app.iu==1) % English
                pm=pm/386;
            end  
                
            [app.global_stiff,app.global_mass,app.global_stiff_unc,app.global_mass_unc,app.dof,app.dof_status,XL,ivector]=...
                         beam_assembly_unc_alt(app.ne,klocal,mlocal,app.LBC,app.RBC,app.dof,npm,pmloc,pm,app.xx);     
             
            if(app.ne==1)
                disp('Unconstrained Global Stiffness Matrix');
                app.global_stiff_unc
                disp('Unconstrained Global Mass Matrix');
                app.global_mass_unc              
                
                disp('Constrained Global Stiffness Matrix');
                app.global_stiff
                disp('Constrained Global Mass Matrix');
                app.global_mass

                disp('app.dof')
                app.dof
                disp('app.dof_status')
                app.dof_status
                disp('ivector')
                ivector
            end
          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(npm>=1)
             
                disp(' ');
                disp(' Point mass ');
             
               for i=1:npm
            
                    if(app.iu==1) % English   
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i)*386);
                    else
                        fprintf('%7.3g  %7.3g \n',XL(i),pm(i));       
                    end
               
               end
            end
                                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            
            pms=sum(pm);
                
            tmass=mass+pms;
                
            if(app.iu==1) % English    
                fprintf('\n Total mass = %8.4g lbm \n',tmass*386);
            else
                fprintf('\n Total mass = %8.4g kg \n',tmass);        
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.global_stiff,app.global_mass,2);
            
            [app.part]=beam_fn_pf_emm(app.iu,tmass,app.dof,app.fn,app.global_mass,app.ModeShapes,app.MST,app.dof_status,ivector);  % function
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.fn=fix_size(app.fn);
            
            dtype=2;
   
            etype=1;  % enforced acceleration     
            
            [pmass,pstiff,app.nem,app.num,app.nff,ea,app.ngw,app.pdof,app.response_dof]=...
                               beam_partition(app.BC,app.global_mass_unc,app.global_stiff_unc);
            
            [app.TT,~,~,app.Mwd,app.Mww,~,app.Kww]=enforced_partition_matrices(app.num,ea,pmass,pstiff,etype,dtype);

            [~,app.pomegan,app.pomn2,app.pModeShapes,app.pMST,~]=enforced_acceleration_eigenvalues(app.Kww,app.Mww,app.num,app.nff);
            
            disp(' ');
            disp(' Output arrays saved as: ');
            disp(' ');
            disp('    beam_fn ');
            disp('    beam_pf ');
            disp('    beam_modeshapes ');
            
            disp(' ');
            disp(' Mass & Stiffness arrays saved as:  ');
            disp(' ');
            disp('    beam_mass');
            disp('    beam_stiffness');
            
            assignin('base', 'beam_fn', app.fn);
            assignin('base', 'beam_pf', app.part);
            assignin('base', 'beam_modeshapes', app.ModeShapes);
            
            assignin('base', 'beam_stiffness', app.global_stiff);
            assignin('base', 'beam_mass', app.global_mass);

            num_fn=length(app.fn);
            
            app.s1='a';
            app.s2='a';
            app.s3='a';
            app.s4='a';
            app.s5='a';
            app.s6='a';
            
            app.s1=sprintf('%8.4g',app.fn(1));
            sss={app.s1};  
            
            if(num_fn>=2)
                app.s2=sprintf('%8.4g',app.fn(2));
                sss={app.s1;app.s2};  
            end
            if(num_fn>=3)
                app.s3=sprintf('%8.4g',app.fn(3));
                sss={app.s1;app.s2;app.s3};
            end
            if(num_fn>=4)
                app.s4=sprintf('%8.4g',app.fn(4));
                sss={app.s1;app.s2;app.s3;app.s4};
            end
            if(num_fn>=5)
                app.s5=sprintf('%8.4g',app.fn(5));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5};
            end
            if(num_fn>=6)
                app.s6=sprintf('%8.4g',app.fn(6));
                sss={app.s1;app.s2;app.s3;app.s4;app.s5;app.s6};
            end
            
            app.SelectModeforPlotListBox.Items=sss;
            
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
            ss=sprintf('Select Mode for Plot \n fn (Hz)');
            app.SelectModeforPlotListBoxLabel.Text=ss;
            app.PlotModeShapeButton.Enable='on';
            
            SaveDampingButtonPushed(app, event);

            msgbox('Natural Frequencies written to Command Window');            

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            ChangeCrossSection(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=8;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6';'Mode 7';'Mode 8'};
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(i)=v;
                    end            
                end   
            end
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            try
                close Figure 1;
            catch
            end 
    
            n=app.SelectModeforPlotListBox.Value;
            
            if(contains(n,app.s1))
                i=1;
            end
            if(contains(n,app.s2))
                i=2;
            end
            if(contains(n,app.s3))
                i=3;
            end            
            if(contains(n,app.s4))
                i=4;
            end
            if(contains(n,app.s5))
                i=5;
            end
            if(contains(n,app.s6))
                i=6;
            end  
            
            UnityModeShapes=app.ModeShapes(:,i);
            
            figure(1);
            
            num=50;
            
            xq=zeros(num*app.ne,1);
            yq=zeros(num*app.ne,1);
            
            kv=1;

            for j=1:app.ne
   
                if(j==1)
                    if(app.LBC==1)  % fixed
                        t1=0;
                        r1=0;
                        t2=UnityModeShapes(1);
                        r2=UnityModeShapes(2);
                        kt=1;
                        kr=2;
                    end
                    if(app.LBC==2)  % pinned
                        t1=0;
                        r1=UnityModeShapes(1);
                        t2=UnityModeShapes(2);
                        r2=UnityModeShapes(3);
                        kt=2;
                        kr=3;                        
                    end    
                    if(app.LBC==3)   % free
                        t1=UnityModeShapes(1);
                        r1=UnityModeShapes(2);
                        t2=UnityModeShapes(3);
                        r2=UnityModeShapes(4);
                        kt=3;
                        kr=4;                        
                    end   
                end    
                if(j>=2 && j<=app.ne-1)
                    t1=UnityModeShapes(kt);
                    r1=UnityModeShapes(kr);
                    kt=kt+2;
                    kr=kr+2;   
                    t2=UnityModeShapes(kt);
                    r2=UnityModeShapes(kr);
                end

                if(j==app.ne && j>=2)
                    if(app.RBC==1)  % fixed
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=0;
                    end
                    if(app.RBC==2)  % pinned
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=0;
                        r2=UnityModeShapes(end);                    
                    end    
                    if(app.RBC==3)   % free
                        t1=UnityModeShapes(kt);
                        r1=UnityModeShapes(kr);
                        t2=UnityModeShapes(kt+2);
                        r2=UnityModeShapes(kr+2);                    
                    end   
                end                    
                
                h=app.dx(j);
                delta=1/(num-1);
                
                for jk=1:num
                    de=(jk-1)*delta;
                    e=1-de;
                    c1=3*e^2-2*e^3;
                    c2=e^2-e^3;
                    c3=1-3*e^2+2*e^3;
                    c4=-e+2*e^2-e^3;
                    yq(kv)=c1*t1 +c2*h*r1 +c3*t2 + c4*h*r2;
                    xe=h*de;
                    xq(kv)=xe+app.xx(j);
                    % fprintf('j=%d jk=%d kv=%d xq=%6.4g yq=%6.4g \n',j,jk,kv,xq(kv),yq(kv));
                    kv=kv+1;
                end            
            end
            
            yq=yq/(max(abs(yq)));
            plot(xq,yq,'b');    
                
            grid on;
            axis([0,app.L,-1.1,1.1]);
            xlabel('x (inch) ');
            ylabel('Normalized Modal displacement');
            sss=sprintf('Mode %d   fn=%8.4g Hz  with Cubic Interpolation',i,app.fn(i));
            title(sss)

            
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            BeamBaseImage(app);
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end

        % Callback function
        function SettoFirstfnButtonPushed(app, event)
            app.ExcitationFrequencyHzEditField_sine.Value=app.fn(1);
        end

        % Callback function
        function SaveButton_arbitraryPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_arbitrary.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            output_name=strtrim(app.OutputArrayNameEditField_arbitrary.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');         
        end

        % Callback function
        function SaveButton_PSDPushed(app, event)
            n=strtrim(app.OutputFunctionListBox_PSD.Value);
            
            if(contains(n,'Relative Displacement Trans'))
                data=app.rd_response_trans;
            end
            if(contains(n,'Relative Displacement PSD'))
                data=app.rd_response_psd;
            end            
            if(contains(n,'Acceleration Trans'))
                data=app.acc_response_trans;
            end
            if(contains(n,'Acceleration PSD'))
                data=app.acc_response_psd;
            end                 
            if(contains(n,'Bending Stress Trans'))
                data=app.stress_response_trans;
            end
            if(contains(n,'Bending Stress PSD'))
                data=app.stress_response_psd;
            end             
            
            output_name=strtrim(app.OutputArrayNameEditField_PSD.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');       
        end

        % Callback function
        function NumberofModesListBox_HSValueChanged(app, event)
 
        end

        % Callback function
        function BaseInputAccelGEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function PulseDurationsecEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function ResponseLocationEditField_HSValueChanged(app, event)
          
        end

        % Callback function
        function SaveButton_HSPushed(app, event)
            output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
            n=strtrim(app.OutputFunctionListBox_HS.Value);
            if(contains(n,'Relative Displacement'))
                data=app.relative_displacement;
            end
            if(contains(n,'Absolute Acceleration'))
                data=app.acceleration;
            end 
            if(contains(n,'Bending Stress'))
                data=app.bending_stress;
            end
            
            assignin('base',output_name, data); 
            msgbox('Data saved');   
        end

        % Value changed function: NumberofPointMassesListBox
        function NumberofPointMassesListBoxValueChanged(app, event)
            PointMass(app);
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed2(app, event)
            
            app.na='Trans';
            
            PreProcess(app);
            if(app.kflag==999)
                return;
            end   
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');           
            
            num_columns=app.number_modes;
            
            fig_num=1;
                  
            fstart=app.StartFrequencyHzEditField.Value;
            
            if(isempty(fstart))
                warndlg('Enter start frequency');
                return;
            end            
                  
            if(fstart==0)
                fstart=1;
            end
                  
            fend=app.EndFrequencyHzEditField.Value;
            
            if(isempty(fend))
                warndlg('Enter end frequency');
                return;
            end
            
            if(fend<=fstart)
                warndlg('End frequency must be > start frequency');
                return;    
            end
            
            fmax=fend;
            fmin=fstart;

            N=48;
            [freq,omega,app.np]=frequencies_octave_spaced(fmin,fmax,N);

            om2=omega.^2;

            QE=app.ModeShapes;
            fnv=app.fn;
            app.number_modes=length(fnv);
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            sz=size(QE);
            if(num_columns>sz(2))
                num_columns=sz(2);
            end
            
            if(num_columns> length(app.fn))
                num_columns=length(app.fn);
            end 

            [~,HM_accel_response,~,~,HM_rd_response,~,app.adisp]=...
                BeamTransCore(app.nff,app.np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.response_dof);
 
            app.np=length(freq);
            Stress(app);

            if(app.iu==1)
                    y_label_st='Stress/Accel (psi/G)';
            else
                    y_label_st='Stress/Accel (Pa/G)';    
            end
            
            if(app.iu==1)
                    y_label_rd='(Rel Disp)/Accel (in/G)';
            else
                    y_label_rd='(Rel Disp)/Accel (m/G)';    
            end
                
            x_label='Frequency (Hz)';
            y_label_ar='Accel Ratio (G/G)';
            
            fmin=min(freq);
            fmax=max(freq);

            %
            %  http://home.sogang.ac.kr/sites/cmlab/Coursework/course001/Lists/b141/Attachments/1/Ch.02%20(pp.%2017-40)%20Bars%20and%20Beams.%20Linear%20Static%20Analysis.pdf
            %

            HM_rd_response  =abs(HM_rd_response*app.rs);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string_th{1}='Acceleration Trans';
            string_th{2}='Relative Displacement Trans';
            string_th{3}='Stress Trans';           

            app.OutputFunctionListBox.Items=string_th; 

            t_string_at=sprintf('Accleration Transmissibility  x=%s inch',app.xxr);    
            t_string_rt=sprintf('Relative Displacement Transmissibility  x=%s inch',app.xxr);  
            t_string_st=sprintf('Stress Transmissibility  x=%s inch',app.xxr);     

            app.atrans=[freq HM_accel_response];
            app.rdtrans=[freq HM_rd_response];
            app.strans=[freq app.HM_stress_response];   

            [Ca,Ia]=max(HM_accel_response);
            [Cr,Ir]=max(HM_rd_response);
            [Cs,Is]=max(app.HM_stress_response);

            md=4;

            [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_ar,t_string_at,app.atrans,fmin,fmax,md);   
                ss=sprintf(' Max Accel Trans \n   = %7.4g G/G  at %7.4g Hz \n\n',Ca,freq(Ia));

             if( max(app.rdtrans(:,2)) > 1e-20)   
                [fig_num,~]=...
                    plot_loglog_function_md_h2(fig_num,x_label,y_label_rd,t_string_rt,app.rdtrans,fmin,fmax,md); 
                ss=sprintf('%s Max Rel Disp Trans \n   = %7.2e in/G  at %7.4g Hz \n\n',ss,Cr,freq(Ir));             
             end

             if( max(app.strans(:,2)) > 1e-20)  
                 try
                    [fig_num,~]=...
                        plot_loglog_function_md_h2(fig_num,x_label,y_label_st,t_string_st,app.strans,fmin,fmax,md); 
                    ss=sprintf('%s Max Stress Trans \n   = %7.4g psi/G  at %7.4g Hz \n\n',ss,Cs,freq(Is));                
                 catch
                    warndlg('Stress plot failed');
                    return;
                 end
             end
            
            app.ResultsTextArea.Value=ss;
            PostOn(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = char(app.OutputFunctionListBox.Items);
            im=app.OutputFunctionListBox.Value;
            [p]=find_index(Items,im); 

            if(contains(app.na,'Trans'))

                if(p==1)
                    data=app.atrans;
                end
                if(p==2)
                    data=app.rdtrans;
                end
                if(p==3)
                    data=app.strans;
                end
            end
            if(contains(app.na,'Half'))

                if(p==1)
                    data=app.acceleration;
                end
                if(p==2)
                    data=app.relative_disp;
                end
                if(p==3)
                    data=app.bending_stress;
                end
            end

            output_name=strtrim(app.OutputArrayNameEditField.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');     
        end

        % Button pushed function: CalculateBaseInputSine
        function CalculateBaseInputSineButtonPushed2(app, event)

            PreProcess(app);
            if(app.kflag==999)
                return;
            end             

            app.na='Sine';
            
            freq=str2num(app.ExcitationFreqHzEditField.Value);
            omega=2*pi*freq;
            base=str2num(app.BaseAccelerationGEditField.Value);
            
            if(app.kflag==0)
                warndlg('Boundary condition case unavailable.')
                return;
            end
        
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end      
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            nu=app.number_modes;
         
            num_columns=min([nu length(app.fn)]);    

            om2=omega.^2;
         
            fnv=app.fn;
            app.number_modes=length(fnv);
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            
            app.np=1;

            [~,HM_accel_response,~,~,HM_rd_response,~,app.adisp]=...
                BeamTransCore(app.nff,app.np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.response_dof);
            
            app.acc=base*HM_accel_response;

            HM_rd_response  =abs(HM_rd_response*app.rs);
            rel_disp=base*HM_rd_response;

            Stress(app);

            omega=2*pi*freq;

            ss1=sprintf(' Accel = %8.4g G \n\n',app.acc);
            ss2=sprintf(' Pseudo Vel = %8.4g in/sec \n\n',rel_disp*omega);
            ss3=sprintf(' Rel Disp = %8.4g in \n\n',rel_disp);
            ss4=sprintf(' Stress = %8.4g psi',app.HM_stress_response);

            ss=sprintf('%s %s %s %s ',ss1,ss2,ss3,ss4);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';     

        end

        % Callback function
        function CalculateBaseInput_HS_2ButtonPushed(app, event)

        end

        % Button pushed function: CalculateBaseInputPSD_2
        function CalculateBaseInputPSD_2ButtonPushed(app, event)

            PreProcess(app);
            if(app.kflag==999)
                return;
            end   

            disp(' ');
            disp(' * * * * * ');
            disp(' ');            
            
            if(app.BC==1)
                disp(' Fixed-Free Beam');
            end
            if(app.BC==2)
                disp(' Pinned-Pinned Beam');    
            end
            if(app.BC==3)
                disp(' Fixed-Fixed Beam');    
            end
            if(app.BC==4)
                disp(' Fixed-Pinned Beam');    
            end          
            disp(' ');

            try
                FS=app.InputArrayNameEditField_PSD.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg(' Input PSD array not found.');
                return; 
            end    
            
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);

            N=48;
            [freq,omega,app.np]=frequencies_octave_spaced(fmin,fmax,N);

            om2=omega.^2;

            fnv=app.fn;
            app.number_modes=length(fnv);

            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %
            num_columns=str2num(app.NumberofModesListBox_PSD.Value);

            num_columns=min([num_columns length(app.fn)  ]);
            
            fprintf('\n number of modes included =%d \n',num_columns);
                      
            [~,HM_accel_response,HM_accel_right,HM_rd_left,HM_rd_response,HM_rd_right,app.adisp]=...
                BeamTransCore(app.nff,app.np,app.num,app.nem,app.dm,app.pMST,num_columns,app.pomn2,om2,app.pomegan,...
                omega,app.TT,app.pdof,app.Mwd,app.pModeShapes,app.ngw,app.response_dof);
            
            dxx=mean(app.dx);
            pL=dxx;

            %
            %  http://home.sogang.ac.kr/sites/cmlab/Coursework/course001/Lists/b141/Attachments/1/Ch.02%20(pp.%2017-40)%20Bars%20and%20Beams.%20Linear%20Static%20Analysis.pdf
            %

            if(app.iu==1)
               app.rs=386; 
            else
               app.rs=9.81; 
            end

            HM_rd_left =abs(HM_rd_left*app.rs);
            HM_rd_response  =abs(HM_rd_response*app.rs);
            HM_rd_right=abs(HM_rd_right*app.rs);
            
            HM_stress_left=zeros(app.np,1);
            app.HM_stress_response=zeros(app.np,1);
            HM_stress_right=zeros(app.np,1);

            mid=(app.ne+1);            % equivalent to rotational dof number at center of beam
            Y=zeros(app.np,2*(app.ne+1));  % total unconstrained dof 
            
            if(app.BC==1) % Fixed-Free
               for j=1:app.np
                   Y(j,:)=[app.adisp(j,1) 0 app.adisp(j,2:end)];
               end     
            end
            if(app.BC==2) % Simply-Supported 
                for j=1:app.np
                   Y(j,:)=app.adisp(j,:);
                end 
            end  
            if(app.BC==3) % Fixed-Fixed
               for j=1:app.np
                   Y(j,:)=[app.adisp(j,1) 0 app.adisp(j,2:end) 0];
               end              
            end
            
            s=0;  % left side of element
            [B]=beam_stress_B(s,pL);
            
            for j=1:app.np
                d=[Y(j,1) Y(j,2) Y(j,3) Y(j,4) ]';
                HM_stress_left(j)=app.rs*abs(app.cna*app.E*B*d); 
            end

            s=1; % right side of element
            [B]=beam_stress_B(s,pL);
            
            neh=app.ne/2;
            
            for j=1:app.np
                k=2*neh+1;
                d=[Y(j,k)  Y(j,k+1) Y(j,k+2) Y(j,k+3) ]';            
                app.HM_stress_response(j)=app.rs*abs(app.cna*app.E*B*d);                
                d=[Y(j,end-3)  Y(j,end-2) Y(j,end-1) Y(j,end) ]'; 
                HM_stress_right(j)=app.rs*abs(app.cna*app.E*B*d);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % HM_accel_left=[freq HM_accel_left  ];
            HM_accel_response=[freq  HM_accel_response ];
            HM_accel_right=[freq  HM_accel_right ];
             
            % HM_rd_left=[freq HM_rd_left  ];
            HM_rd_response=[freq  HM_rd_response ];
            HM_rd_right=[freq HM_rd_right  ];
             
            HM_stress_left=[freq  HM_stress_left ];
            app.HM_stress_response=[freq app.HM_stress_response  ];
            HM_stress_right=[freq HM_stress_right  ];
            
            assignin('base','HL',HM_stress_left);
            assignin('base','HM',app.HM_stress_response);
            assignin('base','HR',HM_stress_right);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=min(freq);
            fmax=max(freq);
            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                    y_label_stress='Stress (psi^2/Hz)';
            else
                    y_label_stress='Stress (Pa^2/Hz)';    
            end
            
            y_label_G2pHz='Accel (G^2/Hz)';
            y_label_G2G2='Accel Ratio (G^2/G^2)';
            
            if(app.iu==1)
                    y_label_accel_ratio='Accel Ratio (in^2/G^2)';
            else
                    y_label_accel_ratio='Accel Ratio (m^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_stress_accel='Stress/Accel (psi^2/G^2)';
            else
                    y_label_stress_accel='Stress/Accel (Pa^2/G^2)';    
            end
            
            if(app.iu==1)
                    y_label_rel_disp='Rel Disp (in^2/Hz)';
            else
                    y_label_rel_disp='Rel Disp (m^2/Hz)';
            end

            md=6;
            
            fig_num=1;
            
            PSD_in=app.THM;
            
            if(app.BC==1)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_right);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_right);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,HM_stress_left);             
            end
            if(app.BC==2)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_response);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_response);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,app.HM_stress_response);       
            end
            if(app.BC==3)
                [app.acc_response_psd]=psd_mult_trans(PSD_in,HM_accel_response);
                [app.rd_response_psd]=psd_mult_trans(PSD_in,HM_rd_response);
                [app.stress_response_psd]=psd_mult_trans(PSD_in,HM_stress_left);      
            end

            [~,accel_rms]=calculate_PSD_slopes_no( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  ); 
            [~,rd_rms]=calculate_PSD_slopes_no( app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );    
            [~,stress_rms]=calculate_PSD_slopes_no( app.stress_response_psd(:,1),app.stress_response_psd(:,2)  ); 

            ppp_apsd=app.acc_response_psd;    
            ppp_rdpsd=app.rd_response_psd; 
            ppp_spsd=app.stress_response_psd;

            if(app.BC==1)
                ppp_aptrans=[freq HM_accel_right(:,2).^2];
                ppp_rdptrans=[freq HM_rd_right(:,2).^2];  
                ppp_sptrans=[freq HM_stress_left(:,2).^2];    
            end
            if(app.BC==2)
                ppp_aptrans=[freq HM_accel_response(:,2).^2];
                ppp_rdptrans=[freq HM_rd_response(:,2).^2]; 
                ppp_sptrans=[freq app.HM_stress_response(:,2).^2];        
            end
            if(app.BC==3)
                ppp_aptrans=[freq HM_accel_response(:,2).^2];
                ppp_rdptrans=[freq HM_rd_response(:,2).^2];       
                ppp_sptrans=[freq HM_stress_left(:,2).^2];   
            end
            
            app.acc_response_trans=ppp_aptrans;    
            app.rd_response_trans=ppp_rdptrans; 
            app.stress_response_trans=ppp_sptrans;            

            if(app.BC==1)
                t_string_at='Accleration Power Transmissibility at Free End ';  
                t_string_aa=sprintf('Accleration PSD at Free End  %7.3g GRMS',accel_rms);      
                t_string_st='Stress Power Transmissibility at Fixed End ';    

                if(app.iu==1)
                    t_string_rr=sprintf('Rel Disp PSD at Free End Relative to Base %7.3g in RMS',rd_rms);
                else
                    t_string_rr=sprintf('Rel Disp PSD at Free End Relative to Base %7.3g m RMS',rd_rms);    
                end    
            
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Fixed End  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Fixed End  %7.4g Pa GRMS',stress_rms);
                end    
                
            end
            if(app.BC>=2)
                
                t_string_at='Accleration Power Transmissibility at Midspan '; 
                t_string_aa=sprintf('Accleration PSD at Midspan  %7.3g GRMS',accel_rms);       
            
                if(app.iu==1)
                    t_string_rr=sprintf('Rel Disp PSD at Midspan Relative to Base %7.3g in RMS',rd_rms);
                else
                    t_string_rr=sprintf('Rel Disp PSD at Midspan Relative to Base %7.3g m RMS',rd_rms);    
                end      
                
            end
            if(app.BC==2)
                
                t_string_st='Stress Power Transmissibility at Midspan ';  
                
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Midspan  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Midspan  %7.4g Pa GRMS',stress_rms);
                end     
            end

            if(app.BC==3)
                
                t_string_st='Stress Power Transmissibility at Left Fixed End ';  
                
                if(app.iu==1)
                    t_string_ss=sprintf('Stress PSD at Left Fixed End  %7.4g psi RMS',stress_rms);
                else    
                    t_string_ss=sprintf('Stress PSD at Left Fixed End  %7.4g Pa GRMS',stress_rms);
                end     
            end
            
 
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_G2G2,t_string_at,ppp_aptrans,fmin,fmax,md);  
               
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_G2pHz,t_string_aa,ppp_apsd,fmin,fmax,md); 

            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_rel_disp,t_string_rr,ppp_rdpsd,fmin,fmax,md);    
                
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_stress_accel,t_string_st,ppp_sptrans,fmin,fmax,md); 
            
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label_stress,t_string_ss,ppp_spsd,fmin,fmax,md);

            app.SaveButton.Enable='on';
        end

        % Button pushed function: CalculateBaseInputMDOFSRS_2
        function CalculateBaseInputMDOFSRS_2ButtonPushed(app, event)
            
            PreProcess(app);   
            if(app.kflag==999)
                return;
            end            

            num_srs=length(app.THM(:,1));
            num_fn=length(app.fn);   
            
            nL=str2num(app.NumberofModesListBox_MDOFSRS.Value);
            
            num_fn=min([nL num_fn]);
            
            disp(' ');
            disp(' * * * * * * * * * * ');
            disp(' ');
            
            fig_num=1;
            
            app.iu=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       
            Q=app.SpecificationQEditField.Value;
    
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                             
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.fn(1)<app.THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            if(app.fn(num_fn)>app.THM(num_srs,1))
               warndlg(' Last Natural Frequency < Last Specification Frequency ');
               return
            end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                          
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 

            app.xx=[0. 0.5 1]*app.L;
            nxx=length(app.xx);
            
            sz=size(app.ModeShapes);
            app.pdof=sz(1);
            
            ev=zeros(3,sz(2));
            
            if(app.BC==1)
                app.response_dof=floor(app.pdof/2);
                ev(3,:)=app.ModeShapes(end-1,:);
            end
            if(app.BC==2)
                app.response_dof=round(app.pdof/2);
            end
            if(app.BC==3)
                app.response_dof=round(app.pdof/2);
            end
            ev(2,:)=app.ModeShapes(app.response_dof,:);  
            
            accel_abssum=zeros(nxx,1);
              accel_srss=zeros(nxx,1);
              
            pv_abssum=zeros(nxx,1);
              pv_srss=zeros(nxx,1);  
            
            rd_abssum=zeros(nxx,1);
              rd_srss=zeros(nxx,1);
            
            for i=1:nxx

                for j=1:num_fn
                    
                    PFM=app.part(j)*ev(i,j);
                    % fprintf(' i=%d  j=%d  PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,PFM,j,a_srs(j));
                    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            disp(' ');
            disp(' Acceleration');
            
            if(app.iu==1)
                out_a='     x(in)   SRSS(G)  Abssum(G)';
            else
                out_a='     x(m)    SRSS(G)  Abssum(G)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),accel_srss(i),accel_abssum(i));
            end
            
            disp(' ');
            disp(' Pseudo Velocity');
            
            if(app.iu==1)
                out_a='     x(in) SRSS(in/sec)  Abssum(in/sec)';
            else
                out_a='     x(m)  SRSS(cm/sec)  Abssum(cm/sec)';   
            end
            disp(' ');
            disp(out_a);          
            
            for i=1:nxx
                fprintf('    %7.3f  %8.4g  %8.4g \n',app.xx(i),pv_srss(i),pv_abssum(i));
            end
            
            disp(' ');
            disp(' Relative Displacement');
                
            if(app.iu==1)
                out_a='       x(in)  SRSS(in)  Abssum(in)';
            else
                out_a='       x(m)   SRSS(mm)  Abssum(mm)';   
            end
                
            disp(' ');
            disp(out_a);
            
            for i=1:nxx
                fprintf('     %7.3f  %8.4g  %8.4g \n',app.xx(i),rd_srss(i),rd_abssum(i));
            end

            msgbox('Results written to Command Window');
            
        end

        % Button pushed function: CalculateBaseInputArbitrary_2
        function CalculateBaseInputArbitrary_2ButtonPushed(app, event)

            disp('ref 1')
        
            try
                CloseFigures(app);
            catch
                warndlg('CloseFigures failed');
                return;
            end

            disp('ref 2')

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im)
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end

            disp('ref 3')
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp('ref 5')
            
            try
                [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            catch
                warndlg('check failed');
                return;
            end

            if(app.kflag==999)
                return;
            end   

            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            app.nde=1;  %  detrend

            beam_FEA_base_arbitrary(app);
               
            PostOn(app);   
   
            % app.SaveButton.Enable='on';

            msgbox('Calculation complete.  Output written to Matlab Command Window.');      
        end

        % Button down function: BaseExcitationResponseTab
        function BaseExcitationResponseTabButtonDown(app, event)
            PostOff(app);
        end

        % Value changed function: ResponseLocationListBox
        function ResponseLocationListBoxValueChanged(app, event)
            PostOff(app);
        end

        % Value changed function: NumberofModesListBox
        function NumberofModesListBoxValueChanged(app, event)
            PostOff(app); 
            app.number_modes=str2num(app.NumberofModesListBox.Value);
        end

        % Selection change function: TabGroupBase
        function TabGroupBaseSelectionChanged(app, event)
            PostOff(app);        
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            PostOff(app);
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed2(app, event)
            app.ExcitationFreqHzEditField.Value=sprintf('%8.4g',app.fn(1));
            PostOff(app);
        end

        % Value changing function: ExcitationFreqHzEditField
        function ExcitationFreqHzEditFieldValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: BaseAccelerationGEditField
        function BaseAccelerationGEditFieldValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: BaseInputAccelGEditField_3
        function BaseInputAccelGEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: PulseDurationsecEditField_3
        function PulseDurationsecEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Value changing function: TotalResponseDurationsecEditField_3
        function TotalResponseDurationsecEditField_3ValueChanging(app, event)
            PostOff(app);
        end

        % Button pushed function: CalculateResponseButton
        function CalculateResponseButtonPushed(app, event)
    
            clear app.THM;

            PreProcess(app);          
            if(app.kflag==999)
                return;
            end   

            app.na='Half-Sine';
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
                        
            base=str2num(app.BaseInputAccelGEditField_3.Value);
            dur=str2num(app.PulseDurationsecEditField_3.Value);
            TTotal=str2num(app.TotalResponseDurationsecEditField_3.Value);
            
            %
            nrb=0;
            for i=1:app.number_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf(' %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            %      

            sr1=32/dur;
            sr2=12*app.fn(app.number_modes);
            
            sr=max([sr1 sr2]);
         
            app.dt=1/sr;
            
            NT=round(TTotal/app.dt);
            app.nt=NT;
            
            app.THM=zeros(NT,2);
            app.THM(:,1)=((1:NT)-1)*app.dt;

            NTE=floor(dur/app.dt);
            
            omega=pi/dur;
            
            for i=1:NTE
                app.THM(i,2)=base*sin(omega*app.THM(i,1));
            end
                        
            app.nde=1;  %  detrend

            beam_FEA_base_arbitrary(app);
               
            PostOn(app);   
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
            Items = char(app.SelectInputArrayListBox_psd.Items);
            im=app.SelectInputArrayListBox_psd.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
            
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_th(app);
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
              Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_th(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIBeamBendingFEAUIFigure = uifigure('Visible', 'off');
            app.UIBeamBendingFEAUIFigure.Name = 'UI Beam Bending FEA';
            app.TabGroup = uitabgroup(app.UIBeamBendingFEAUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'numeric');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'numeric');
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Fixed';
            app.NumberofElementsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.NumberofElementsListBoxLabel.Text = 'Number of Elements';
            app.NumberofElementsListBox = uilistbox(app.GeometryBCsTab);
            app.NumberofElementsListBox.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512'};
            app.NumberofElementsListBox.Value = '1';
            app.MaterialAddedMassTab = uitab(app.TabGroup);
            app.MaterialAddedMassTab.Title = 'Material & Added Mass';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.MaterialLabel = uilabel(app.MaterialAddedMassTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialAddedMassTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNSMEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.NumberofPointMassesListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBoxLabel.Text = 'Number of Point Masses';
            app.NumberofPointMassesListBox = uilistbox(app.MaterialAddedMassTab);
            app.NumberofPointMassesListBox.Items = {'0', '1'};
            app.NumberofPointMassesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofPointMassesListBoxValueChanged, true);
            app.NumberofPointMassesListBox.Value = '0';
            app.PointMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMasslbmEditFieldLabel.Text = 'Point Mass (lbm)';
            app.PointMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.PointMassLocationfromLeftEndinEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.PointMassLocationfromLeftEndinEditFieldLabel.Text = 'Point Mass Location from Left End (in)';
            app.PointMassLocationfromLeftEndinEditField = uieditfield(app.MaterialAddedMassTab, 'numeric');
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFrequenciesTab);
            app.CalculateNaturalFrequenciesPartitionButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequenciesPartitionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesPartitionButtonPushed, true);
            app.CalculateNaturalFrequenciesPartitionButton.Text = 'Calculate Natural Frequencies & Partition';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {''};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.BaseExcitationResponseTab = uitab(app.TabGroup);
            app.BaseExcitationResponseTab.Title = 'Base Excitation Response';
            app.BaseExcitationResponseTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationResponseTabButtonDown, true);
            app.ResponseLocationinchfromLeftEndLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResponseLocationinchfromLeftEndLabel.Text = {'Response '; 'Location (inch) '; 'from Left End'};
            app.ResponseLocationListBox = uilistbox(app.BaseExcitationResponseTab);
            app.ResponseLocationListBox.Items = {'Item 1'};
            app.ResponseLocationListBox.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationListBoxValueChanged, true);
            app.TabGroupBase = uitabgroup(app.BaseExcitationResponseTab);
            app.TabGroupBase.SelectionChangedFcn = createCallbackFcn(app, @TabGroupBaseSelectionChanged, true);
            app.TransTab = uitab(app.TabGroupBase);
            app.TransTab.Title = 'Trans';
            app.StartFrequencyHzEditField_2Label = uilabel(app.TransTab);
            app.StartFrequencyHzEditField_2Label.Text = 'Start Frequency (Hz)';
            app.StartFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.EndFrequencyHzEditField_2Label = uilabel(app.TransTab);
            app.EndFrequencyHzEditField_2Label.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.TransTab, 'numeric');
            app.CalculateBaseInputTrans = uibutton(app.TransTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed2, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.SineTab = uitab(app.TabGroupBase);
            app.SineTab.Title = 'Sine';
            app.SettoFirstfnButton = uibutton(app.SineTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed2, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.CalculateBaseInputSine = uibutton(app.SineTab, 'push');
            app.CalculateBaseInputSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputSineButtonPushed2, true);
            app.CalculateBaseInputSine.Text = 'Calculate';
            app.BaseAccelerationGEditFieldLabel = uilabel(app.SineTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.SineTab, 'text');
            app.BaseAccelerationGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelerationGEditFieldValueChanging, true);
            app.BaseAccelerationGEditField.Value = '1';
            app.ExcitationFreqHzEditFieldLabel = uilabel(app.SineTab);
            app.ExcitationFreqHzEditFieldLabel.Text = 'Excitation Freq (Hz)';
            app.ExcitationFreqHzEditField = uieditfield(app.SineTab, 'text');
            app.ExcitationFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @ExcitationFreqHzEditFieldValueChanging, true);
            app.HalfSineTab = uitab(app.TabGroupBase);
            app.HalfSineTab.Title = 'Half-Sine';
            app.BaseInputAccelGEditField_3Label = uilabel(app.HalfSineTab);
            app.BaseInputAccelGEditField_3Label.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.BaseInputAccelGEditField_3.ValueChangingFcn = createCallbackFcn(app, @BaseInputAccelGEditField_3ValueChanging, true);
            app.PulseDurationsecEditField_3Label = uilabel(app.HalfSineTab);
            app.PulseDurationsecEditField_3Label.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.PulseDurationsecEditField_3.ValueChangingFcn = createCallbackFcn(app, @PulseDurationsecEditField_3ValueChanging, true);
            app.TotalResponseDurationsecEditField_3Label = uilabel(app.HalfSineTab);
            app.TotalResponseDurationsecEditField_3Label.Text = 'Total Response Duration (sec)';
            app.TotalResponseDurationsecEditField_3 = uieditfield(app.HalfSineTab, 'text');
            app.TotalResponseDurationsecEditField_3.ValueChangingFcn = createCallbackFcn(app, @TotalResponseDurationsecEditField_3ValueChanging, true);
            app.CalculateResponseButton = uibutton(app.HalfSineTab, 'push');
            app.CalculateResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateResponseButtonPushed, true);
            app.CalculateResponseButton.Text = 'Calculate Response';
            app.PSDTab = uitab(app.TabGroupBase);
            app.PSDTab.Title = 'PSD';
            app.CalculateBaseInputPSD_2 = uibutton(app.PSDTab, 'push');
            app.CalculateBaseInputPSD_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputPSD_2ButtonPushed, true);
            app.CalculateBaseInputPSD_2.Text = 'Calculate';
            app.listbox_method_psd = uilistbox(app.PSDTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.PSDTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.SelectInputArrayListBox_2Label = uilabel(app.PSDTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.PSDTab);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.PSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.UpdateListButton_psd = uibutton(app.PSDTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.ArbitraryTab = uitab(app.TabGroupBase);
            app.ArbitraryTab.Title = 'Arbitrary ';
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel_2 = uilabel(app.ArbitraryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel_2.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.CalculateBaseInputArbitrary_2 = uibutton(app.ArbitraryTab, 'push');
            app.CalculateBaseInputArbitrary_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitrary_2ButtonPushed, true);
            app.CalculateBaseInputArbitrary_2.Text = 'Calculate';
            app.listbox_method = uilistbox(app.ArbitraryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ArbitraryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ArbitraryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ArbitraryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ArbitraryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.MDOFSRSTab = uitab(app.TabGroupBase);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.MDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: '; ''; 'Natural Frequency (Hz) & SRS (G)'; ''};
            app.SpecificationQEditFieldLabel = uilabel(app.MDOFSRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.MDOFSRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.InputArrayNameEditField_2Label_4 = uilabel(app.MDOFSRSTab);
            app.InputArrayNameEditField_2Label_4.Text = 'Input Array Name';
            app.InputArrayNameEditField_MDOFSRS = uieditfield(app.MDOFSRSTab, 'text');
            app.CalculateBaseInputMDOFSRS_2 = uibutton(app.MDOFSRSTab, 'push');
            app.CalculateBaseInputMDOFSRS_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputMDOFSRS_2ButtonPushed, true);
            app.CalculateBaseInputMDOFSRS_2.Text = 'Calculate';
            app.NumberofModesListBox_3Label_3 = uilabel(app.BaseExcitationResponseTab);
            app.NumberofModesListBox_3Label_3.Text = 'Number of Modes';
            app.NumberofModesListBox = uilistbox(app.BaseExcitationResponseTab);
            app.NumberofModesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofModesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofModesListBoxValueChanged, true);
            app.NumberofModesListBox.Value = '1';
            app.ImageBeamBase = uiimage(app.BaseExcitationResponseTab);
            app.SaveButton = uibutton(app.BaseExcitationResponseTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputFunctionListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.OutputFunctionListBoxLabel.Text = 'Output Function';
            app.OutputFunctionListBox = uilistbox(app.BaseExcitationResponseTab);
            app.OutputFunctionListBox.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.OutputArrayNameEditFieldLabel = uilabel(app.BaseExcitationResponseTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.BaseExcitationResponseTab, 'text');
            app.ResultsTextAreaLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.BaseExcitationResponseTab);
            app.ReturnButton = uibutton(app.UIBeamBendingFEAUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.beam_bending_aver10byTomIrvineLabel = uilabel(app.UIBeamBendingFEAUIFigure);
            app.beam_bending_aver10byTomIrvineLabel.Text = 'beam_bending_FEA_a    ver 1.4  by Tom Irvine      This script calculates the natural frequencies of a beam and its response to excitation via FEA.';
            % Show the figure after all components are created
            app.UIBeamBendingFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frgsdfgsfd

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIBeamBendingFEAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIBeamBendingFEAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIBeamBendingFEAUIFigure)
        end
    end
end