classdef integrate_differentiate_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        IntegrateorDifferentiateTab     matlab.ui.container.Tab
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        ReturnButton                    matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        IntegrateTab_3                  matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        InputYaxisLabelEditField_2Label_2  matlab.ui.control.Label
        HighPassFilterHzEditField       matlab.ui.control.EditField
        HighPassFilterHzEditFieldLabel  matlab.ui.control.Label
        SelectTrendRemovalAfterIntegrationListBox  matlab.ui.control.ListBox
        SelectTrendRemovalAfterIntegrationListBoxLabel  matlab.ui.control.Label
        SelectTrendRemovalPriortoIntegrationListBox  matlab.ui.control.ListBox
        SelectTrendRemovalPriortoIntegrationLabel  matlab.ui.control.Label
        OutputYaxisLabelEditField       matlab.ui.control.EditField
        InputYaxisLabelEditField_2Label  matlab.ui.control.Label
        InputYaxisLabelEditField        matlab.ui.control.EditField
        InputYaxisLabelEditFieldLabel   matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.EditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        MultiplybyScaleFactorListBox    matlab.ui.control.ListBox
        MultiplybyScaleFactorLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalculateButton_integrate       matlab.ui.control.Button
        DoubleIntegrateTab_2            matlab.ui.container.Tab
        OutputNameEditField_double      matlab.ui.control.EditField
        OutputNameEditField_doubleLabel  matlab.ui.control.Label
        SaveButton_double               matlab.ui.control.Button
        ExportFunctionListBox           matlab.ui.control.ListBox
        ExportFunctionListBoxLabel      matlab.ui.control.Label
        DurationTaperPercentEditField   matlab.ui.control.EditField
        DurationTaperPercentLabel       matlab.ui.control.Label
        HighPassFilterHzEditField_double  matlab.ui.control.EditField
        HighPassFilterHzEditField_doubleLabel  matlab.ui.control.Label
        T3_ListBox                      matlab.ui.control.ListBox
        SelectTrendRemovalAfterIntegrationListBox_3Label  matlab.ui.control.Label
        T2_ListBox                      matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel  matlab.ui.control.Label
        T1_ListBox                      matlab.ui.control.ListBox
        SelectTrendRemovalPriortoIntegrationListBox_2Label  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        CalculateButton_double          matlab.ui.control.Button
        DifferentitateTab               matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        OutputArrayNameEditField_d      matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        OutputYaxisLabelEditField_d     matlab.ui.control.EditField
        OutputYaxisLabelEditField_2Label  matlab.ui.control.Label
        InputYaxisLabelEditField_d      matlab.ui.control.EditField
        InputYaxisLabelEditField_3Label  matlab.ui.control.Label
        ScaleFactorEditField_d          matlab.ui.control.EditField
        ScaleFactorEditField_dLabel     matlab.ui.control.Label
        DividebyScaleFactorListBox      matlab.ui.control.ListBox
        DividebyScaleFactorListBoxLabel  matlab.ui.control.Label
        CalculateButton_differ          matlab.ui.control.Button
        CorrectAccelerationTab          matlab.ui.container.Tab
        ScriptInformationButton         matlab.ui.control.Button
        OutputNameEditField_cor         matlab.ui.control.EditField
        OutputNameEditFieldLabel_cor    matlab.ui.control.Label
        ExportFunctionListBox_cor       matlab.ui.control.ListBox
        ExportFunctionListBoxLabel_cor  matlab.ui.control.Label
        DurationTaperPercentEditField_cor  matlab.ui.control.EditField
        DurationTaperPercentEditFieldLabel_cor  matlab.ui.control.Label
        HighPassFilterHzEditField_cor   matlab.ui.control.EditField
        HighPassFilterHzEditFieldLabel_cor  matlab.ui.control.Label
        T3_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalAfterIntegrationListBox_corLabel  matlab.ui.control.Label
        T2_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor  matlab.ui.control.Label
        T1_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalPriortoIntegrationListBox_corLabel  matlab.ui.control.Label
        UnitsListBox_cor                matlab.ui.control.ListBox
        UnitsListBoxLabel_cor           matlab.ui.control.Label
        SaveButton_cor                  matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab  matlab.ui.container.Tab
        ResultsTextArea_rd              matlab.ui.control.TextArea
        ResultsTextAreaLabel_rd         matlab.ui.control.Label
        OutputArrayNameEditField_rd     matlab.ui.control.EditField
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        T3_ListBox_rd                   matlab.ui.control.ListBox
        SelectTrendRemovalAfterIntegrationListBox_corLabel_2  matlab.ui.control.Label
        T2_ListBox_rd                   matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2  matlab.ui.control.Label
        T1_ListBox_rd                   matlab.ui.control.ListBox
        SelectTrendRemovalPriortoIntegrationListBox_corLabel_2  matlab.ui.control.Label
        HighPassFilterHzEditField_rd    matlab.ui.control.EditField
        HighPassFilterHzEditFieldLabel_rd  matlab.ui.control.Label
        RelativeDisplacementListBox     matlab.ui.control.ListBox
        RelativeDisplacementListBoxLabel  matlab.ui.control.Label
        UnitsListBox_cor_2              matlab.ui.control.ListBox
        UnitsListBoxLabel_cor_2         matlab.ui.control.Label
        CalculateButton_rd              matlab.ui.control.Button
        SelectResponseInputArrayListBox_rd  matlab.ui.control.ListBox
        SelectResponseInputArrayListBoxLabel_rd  matlab.ui.control.Label
        SelectInputArrayListBox_rd      matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel_rd  matlab.ui.control.Label
        UpdateListButton_rd             matlab.ui.control.Button
        text1_rd                        matlab.ui.control.Label
        ListboxFormat_rd                matlab.ui.control.ListBox
        text1_rd2                       matlab.ui.control.Label
        ListboxMethod_rd                matlab.ui.control.ListBox
        ReturnButton_rd                 matlab.ui.control.Button
        integrate_differentiate_aver13byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM      % Description
        nscale   %
        nscale_d %
        nhp1     %
        nhp_cor  %
        nhp_rd   %  
        dt       %
        method   %
        FS       %
        nT1      %  
        nT1_cor  %
        aa       %
        vv       %
        dd       %
        vvv      %
        ddd      %
        af       %
        vf       %  
        df       % 
        iflagq   %
    end
    
    methods (Access = private)

        function external_array(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end
        end    
        
        function update_rd(app)
            cell_array=update_time_history_23();
            app.SelectInputArrayListBox_rd.Items=unique(sort(cell_array)); 
            app.SelectResponseInputArrayListBox_rd.Items=unique(sort(cell_array));             
        end       

       function Trans_change(app)

            app.SelectResponseInputArrayListBox_rd.Visible='off';
            app.SelectResponseInputArrayListBoxLabel_rd.Visible='off';            

            Items = char(app.ListboxMethod_rd.Items);
            im=app.ListboxMethod_rd.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat_rd.Items);
            im=app.ListboxFormat_rd.Value;
            [m]=find_index(Items,im);

            if(n==2)
                external_array(app);
            end    

            app.SelectInputArrayListBox_rd.Visible='on';
            app.SelectInputArrayListBoxLabel_rd.Visible='on'; 
                
            if(m==1)
                ss=sprintf('Select Common Input Array');
            else
                ss=sprintf('Select Input Array');
                app.SelectResponseInputArrayListBox_rd.Visible='on';
                app.SelectResponseInputArrayListBoxLabel_rd.Visible='on';                
            end

            app.SelectInputArrayListBoxLabel_rd.Text=ss; 
            update_rd(app);

            app.ListboxMethod_rd.Value=app.ListboxMethod_rd.Items{1};

        end                

        function CloseSave_cor(app)
            app.SaveButton_cor.Visible='off';
            app.OutputNameEditField_cor.Visible='off';
            app.OutputNameEditFieldLabel_cor.Visible='off';
            app.ExportFunctionListBox_cor.Visible='off';
            app.ExportFunctionListBoxLabel_cor.Visible='off';    
        end    
        function OpenSave_cor(app)
            app.SaveButton_cor.Visible='on';
            app.OutputNameEditField_cor.Visible='on';
            app.OutputNameEditFieldLabel_cor.Visible='on';
            app.ExportFunctionListBox_cor.Visible='on';
            app.ExportFunctionListBoxLabel_cor.Visible='on';               
        end            
        
        function CloseSave(app)
            app.SaveButton_double.Visible='off';
            app.OutputNameEditField_double.Visible='off';
            app.OutputNameEditField_doubleLabel.Visible='off';
            app.ExportFunctionListBox.Visible='off';
            app.ExportFunctionListBoxLabel.Visible='off';    
        end    
        function OpenSave(app)
            app.SaveButton_double.Visible='on';
            app.OutputNameEditField_double.Visible='on';
            app.OutputNameEditField_doubleLabel.Visible='on';
            app.ExportFunctionListBox.Visible='on';
            app.ExportFunctionListBoxLabel.Visible='on';           
        end            

        function DT1(app)
            Items = char(app.T1_ListBox.Items);
            im=app.T1_ListBox.Value;
            [app.nT1]=find_index(Items,im);

            if(app.nT1~=5)
                app.HighPassFilterHzEditField_double.Visible='off';
                app.HighPassFilterHzEditField_doubleLabel.Visible='off';
            else
                app.HighPassFilterHzEditField_double.Visible='on';
                app.HighPassFilterHzEditField_doubleLabel.Visible='on';
            end                
        end    
        function DT1_cor(app)
            Items = char(app.T1_ListBox_cor.Items);
            im=app.T1_ListBox_cor.Value;
            [app.nT1_cor]=find_index(Items,im);

            if(app.nT1_cor~=5)
                app.HighPassFilterHzEditField_cor.Visible='off';
                app.HighPassFilterHzEditFieldLabel_cor.Visible='off';
            else
                app.HighPassFilterHzEditField_cor.Visible='on';
                app.HighPassFilterHzEditFieldLabel_cor.Visible='on';
            end                
        end   
        function read_data(app)

            ClosePlots(app);

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==1)

                 try
                     app.FS=app.SelectInputArrayListBox.Value;
                 catch
                     disp('app.FS failed');
                     warndlg('app.FS failed');
                     return;
                 end
                 try
                     app.THM=evalin('base',app.FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end  

            [app.THM,app.iflagq]=time_check_with_linear_interpolation(app.THM);
            if(app.iflagq~=1)
	            return
            end

            app.dt=mean(diff(app.THM(:,1)));

        end

        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end        
        
        function ClosePlots(app)
            CloseSave(app);
            CloseSave_cor(app);

            app.ResultsTextArea_rd.Visible='off';
            app.ResultsTextAreaLabel_rd.Visible='off';          

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        function scale(app)
            Items = char(app.MultiplybyScaleFactorListBox.Items);
            im=app.MultiplybyScaleFactorListBox.Value;
            [app.nscale]=find_index(Items,im);

            if(app.nscale==1)
                app.ScaleFactorEditField.Visible='off';
                app.ScaleFactorEditFieldLabel.Visible='off';
            else
                app.ScaleFactorEditField.Visible='on';
                app.ScaleFactorEditFieldLabel.Visible='on';
            end    
        end    
        function scale_d(app)
            Items = char(app.DividebyScaleFactorListBox.Items);
            im=app.DividebyScaleFactorListBox.Value;
            [app.nscale_d]=find_index(Items,im);

            if(app.nscale_d==1)
                app.ScaleFactorEditField_d.Visible='off';
                app.ScaleFactorEditField_dLabel.Visible='off';
            else
                app.ScaleFactorEditField_d.Visible='on';
                app.ScaleFactorEditField_dLabel.Visible='on';
            end    
        end            
        function HP1(app)
            Items = char(app.SelectTrendRemovalPriortoIntegrationListBox.Items);
            im=app.SelectTrendRemovalPriortoIntegrationListBox.Value;
            [app.nhp1]=find_index(Items,im);

            if(app.nhp1~=4)
                app.HighPassFilterHzEditField.Visible='off';
                app.HighPassFilterHzEditFieldLabel.Visible='off';
            else
                app.HighPassFilterHzEditField.Visible='on';
                app.HighPassFilterHzEditFieldLabel.Visible='on';
            end    
        end      
        function HP_cor(app)
            Items = char(app.T1_ListBox_cor.Items);
            im=app.T1_ListBox_cor.Value;
            [app.nhp_cor]=find_index(Items,im);

            if(app.nhp_cor~=4)
                app.HighPassFilterHzEditField_cor.Visible='off';
                app.HighPassFilterHzEditFieldLabel_cor.Visible='off';
            else
                app.HighPassFilterHzEditField_cor.Visible='on';
                app.HighPassFilterHzEditFieldLabel_cor.Visible='on';
            end    
        end   
        function HP_rd(app)
            Items = char(app.T1_ListBox_rd.Items);
            im=app.T1_ListBox_rd.Value;
            [app.nhp_rd]=find_index(Items,im);

            if(app.nhp_rd~=2)
                app.HighPassFilterHzEditField_rd.Visible='off';
                app.HighPassFilterHzEditFieldLabel_rd.Visible='off';
            else
                app.HighPassFilterHzEditField_rd.Visible='on';
                app.HighPassFilterHzEditFieldLabel_rd.Visible='on';
            end    
        end           
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            scale(app);
            HP1(app);
            HP_cor(app);
            HP_rd(app);
            scale_d(app);
            DT1(app);
            Trans_change(app);
        end

        % Button pushed function: ReturnButton_rd
        function ReturnButton_rdPushed(app, event)
            closereq;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Button pushed function: CalculateButton_integrate
        function CalculateButton_integratePushed(app, event)

            read_data(app);

            if(app.iflagq~=1)
	            return
            end

            m=app.nscale;
            
            if(m==2)
            %    
                sp=app.ScaleFactorEditField.Value;
            %    
                if( isempty(sp) )
                    msgbox('Enter Scale Factor');
                    return;
                else
                    scale=str2num(sp);
                end    
            end
            
            YS_input=app.InputYaxisLabelEditField.Value;
            YS_output=app.OutputYaxisLabelEditField.Value;
            
            try
                tt=app.THM(:,1);
            catch
                warndlg('tt failed');
                return;
            end
            
            y=double(app.THM(:,2));

            q=app.nhp1;
            
            if(q==2)
                y=y-mean(y);
            end
            if(q==3)
                y=detrend(y);    
            end
            if(q==4)
                fc=str2num(app.HighPassFilterHzEditField.Value);
                y=detrend(y);      
                [y]=Butterworth_filter_highpass_function(y,fc,app.dt);    
            end
            
            v=app.dt*cumtrapz(y);
            
            v=fix_size(v);
            
            Items = char(app.SelectTrendRemovalAfterIntegrationListBox.Items);
            im=app.SelectTrendRemovalAfterIntegrationListBox.Value;
            [mw]=find_index(Items,im);
            
            if(mw==2)
                v=v-mean(v);    
            end    
            if(mw==3)
                v=detrend(v);        
            end 
            if(mw==4)
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end 
            if(mw==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            if(m==2)
                v=v*scale;
            end
            
                h=figure(1);
                
                subplot(2,1,1);
                plot(app.THM(:,1),app.THM(:,2));
                title('Input Time History');
                ylabel(YS_input)
                grid on;
                yabs=max(abs(app.THM(:,2)));
                [ymax1]=ytick_linear_scale(yabs);
                ylim([-ymax1,ymax1]);                
                
                %
                subplot(2,1,2);
                plot(app.THM(:,1),v);
                title('Integrated Time History');
                xlabel(' Time(sec) ')
                ylabel(YS_output)
                grid on;
                [ymax2]=ytick_linear_scale(max(abs(v)));
                ylim([-ymax2,ymax2]);   

                set(h, 'Position', [0 0 700 450]);

                integ_th=[app.THM(:,1),v];

                name=app.OutputArrayNameEditField.Value;

                assignin('base',name,integ_th);
                update(app);
                
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            read_data(app);

            if(app.iflagq~=1)
	            return
            end

            Items = char(app.UnitsListBox_cor.Items);
            im=app.UnitsListBox_cor.Value;
            [iunit]=find_index(Items,im);
                        
            tt=app.THM(:,1);
            y=double(app.THM(:,2));
 
            I1=app.nT1_cor;

            Items = char(app.T2_ListBox_cor.Items);
            im=app.T2_ListBox_cor.Value;
            [I2]=find_index(Items,im);          

            Items = char(app.T3_ListBox_cor.Items);
            im=app.T3_ListBox_cor.Value;
            [I3]=find_index(Items,im);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 1
            
            if(I1==2)
                y=y-mean(y);
            end
            if(I1==3)
                y=detrend(y);
            end
            if(I1==4)
                n = 2;
                p = polyfit(tt,y,n);
                y= y - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I1==5)
                fc=str2num(app.HighPassFilterHzEditField_cor.Value);
                y=detrend(y);
                % old-school method is more stable than Matlab's filter
                [y]=Butterworth_filter_highpass_function(y,fc,app.dt);
            end
            
            npe=str2num(app.DurationTaperPercentEditField_cor.Value);
           
            if(npe>0)
                [y]=half_cosine_fade_perc(y,npe);
            end

            a=y;
            v=app.dt*cumtrapz(a);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % In Between
            
            if(I2==2)
                v=v-mean(v);
            end
            if(I2==3)
                v=detrend(v);
            end
            if(I2==4)
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
           
            if(npe>0)
                [v]=half_cosine_fade_perc(v,npe);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2
           
            d=app.dt*cumtrapz(v);
            
            d=fix_size(d);
            
            if(I3==2)
                d=d-mean(d);
            end
            if(I3==3)
                d=detrend(d);
            end
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            if(npe>0)
                [d]=half_cosine_fade_perc(d,npe);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (ips)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/s^2)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.vv]=differentiate_function(d,app.dt);
            [app.aa]=differentiate_function(app.vv,app.dt);
            
            if(iunit==1) % G, in/sec, in
                app.aa=app.aa/386;
            end
            if(iunit==2) % G, cm/sec, mm
                app.vv=app.vv/10;
                app.aa=app.aa/(9.81*1000);
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                app.vv=app.vv/10;
                app.aa=app.aa/1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.vvv=app.dt*cumtrapz(app.aa); 
            app.ddd=app.dt*cumtrapz(app.vvv);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iunit==1) % G, in/sec, in
                app.vvv=app.vvv*386;
                app.ddd=app.ddd*386;
            end
            if(iunit==2) % G, cm/sec, mm
                app.vvv=app.vvv*9.81*100;
                app.ddd=app.ddd*9.81*1000;
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                app.vvv=app.vvv*10;
                app.ddd=app.ddd*1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a1=max(abs(a));
            a2=max(abs(a));
            amax=max([a1 a2 ]);
            
            [ya]=ytick_linear_altp(amax);
            
            v1=max(abs(v));
            v2=max(abs(app.vv));
            v3=max(abs(app.vvv));
            vmax=max([v1 v2 v3]);
            [yv]=ytick_linear_altp(vmax);
            
            d1=max(abs(d));
            d3=max(abs(app.ddd));
            dmax=max([d1  d3]);
            [yd]=ytick_linear_altp(dmax);
            
            fig_num=1;
            
            tstart=tt(1);
            tend=1.001*tt(end);
            
            hp=figure(fig_num);
            fig_num=fig_num+1;
            subplot(3,3,1);
            plot(tt,a);
            ylabel(ay);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,2);
            plot(tt,app.aa);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,3);
            plot(tt,app.aa);
            xlim([tstart tend]);
            ylim([-ya(1) ya(1)]);
            grid on;
            
            subplot(3,3,4);
            plot(tt,v);
            ylabel(vy);
            xlim([tstart tend]);
            ylim([-yv(1) yv(1)]);
            grid on;
            
            subplot(3,3,5);
            plot(tt,app.vv);
            ylim([-yv(1) yv(1)]);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,6);
            plot(tt,app.vvv);
            xlim([tstart tend]);
            ylim([-yv(1) yv(1)]);
            grid on;
            
            subplot(3,3,7);
            plot(tt,d);
            xlim([tstart tend]);
            ylabel(dy);
            ylim([-yd(1) yd(1)]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,8);
            plot(tt,d);
            ylim([-yd(1) yd(1)]);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,9);
            plot(tt,app.ddd);
            ylim([-yd(1) yd(1)]);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            set(hp, 'Position', [0 0 850 650]);
            
            %%%

            app.aa=fix_size(app.aa);
            app.vvv=fix_size(app.vvv);
            app.ddd=fix_size(app.ddd);

            app.af=[tt app.aa];
            app.vf=[tt app.vvv];
            app.df=[tt app.ddd];

            OpenSave_cor(app);

        end

        % Button pushed function: CalculateButton_double
        function CalculateButton_doublePushed(app, event)
            read_data(app);

            if(app.iflagq~=1)
	            return
            end

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [iunit]=find_index(Items,im);
                        
            tt=app.THM(:,1);
            y=double(app.THM(:,2));
 
            I1=app.nT1;

            Items = char(app.T2_ListBox.Items);
            im=app.T2_ListBox.Value;
            [I2]=find_index(Items,im);          

            Items = char(app.T3_ListBox.Items);
            im=app.T3_ListBox.Value;
            [I3]=find_index(Items,im);   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 1
            
            if(I1==2)
                y=y-mean(y);
            end
            if(I1==3)
                y=detrend(y);
            end
            if(I1==4)
                n = 2;
                p = polyfit(tt,y,n);
                y= y - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I1==5)
                fc=str2num(app.HighPassFilterHzEditField_double.Value);
                y=detrend(y);
                % old-school method is more stable than Matlab's filter
                [y]=Butterworth_filter_highpass_function(y,fc,app.dt);
            end
            
            npe=str2num(app.DurationTaperPercentEditField.Value);
           
            if(npe>0)
                [y]=half_cosine_fade_perc(y,npe);
            end

            a=y;
            v=app.dt*cumtrapz(a);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % In Between
            
            if(I2==2)
                v=v-mean(v);
            end
            if(I2==3)
                v=detrend(v);
            end
            if(I2==4)
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
           
            if(npe>0)
                [v]=half_cosine_fade_perc(v,npe);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2
           
            d=app.dt*cumtrapz(v);
            
            d=fix_size(d);
            
            if(I3==2)
                d=d-mean(d);
            end
            if(I3==3)
                d=detrend(d);
            end
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            if(npe>0)
                [d]=half_cosine_fade_perc(d,npe);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (in/sec)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/sec)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/sec^2)';
                vy='Vel (cm/sec)';
                dy='Disp (mm)';
            end
            
            fig_num=1;

            try
                plot_avd_time_histories_subplots_altp(fig_num,tt,a,v,d,ay,vy,dy);
            catch
                warndlg('Plot failed');
                return;
            end

            %%%
            
            app.aa=[tt a];
            app.vv=[tt v];
            app.dd=[tt d];
            
            OpenSave(app);

        end

        % Button pushed function: CalculateButton_differ
        function CalculateButton_differPushed(app, event)
            read_data(app);

            if(app.iflagq~=1)
	            return
            end

            YS_input=app.InputYaxisLabelEditField_d.Value;
            YS_output=app.OutputYaxisLabelEditField_d.Value;
            
            y=double(app.THM(:,2));

            [v]=differentiate_function(y,app.dt);

            v=fix_size(v);
 
            m=app.nscale_d;
            
            if(m==2)
            %    
                sp=app.ScaleFactorEditField_d.Value;
            %    
                if( isempty(sp) )
                    msgbox('Enter Scale Factor');
                    return;
                else
                    scale=str2num(sp);
                end    

                v=v/scale;
            end            

            
            h=figure(1);
                
                subplot(2,1,1);
                plot(app.THM(:,1),app.THM(:,2));
                title('Input Time History');
                ylabel(YS_input)
                grid on;
                yabs=max(abs(app.THM(:,2)));
                [ymax1]=ytick_linear_scale(yabs);
                ylim([-ymax1,ymax1]);                
                
                %
                subplot(2,1,2);
                plot(app.THM(:,1),v);
                title('Differentiated Time History');
                xlabel(' Time(sec) ')
                ylabel(YS_output)
                grid on;
                [ymax2]=ytick_linear_scale(v);
                ylim([-ymax2,ymax2]);   

                set(h, 'Position', [0 0 700 450]);

                diff_th=[app.THM(:,1),v];

                name=app.OutputArrayNameEditField_d.Value;

                assignin('base',name,diff_th);
                update(app);
                
              
        end

        % Value changed function: MultiplybyScaleFactorListBox
        function MultiplybyScaleFactorListBoxValueChanged(app, event)
            scale(app);
            ClosePlots(app);
        end

        % Value changed function: 
        % SelectTrendRemovalPriortoIntegrationListBox
        function SelectTrendRemovalPriortoIntegrationListBoxValueChanged(app, event)
            HP1(app);
            ClosePlots(app);
        end

        % Value changing function: ScaleFactorEditField
        function ScaleFactorEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: HighPassFilterHzEditField
        function HighPassFilterHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectTrendRemovalAfterIntegrationListBox
        function SelectTrendRemovalAfterIntegrationListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: DividebyScaleFactorListBox
        function DividebyScaleFactorListBoxValueChanged(app, event)
            scale_d(app);
        end

        % Value changed function: T1_ListBox
        function T1_ListBoxValueChanged(app, event)
            DT1(app);
            ClosePlots(app);
        end

        % Value changed function: HighPassFilterHzEditField_double
        function HighPassFilterHzEditField_doubleValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: DurationTaperPercentEditField
        function DurationTaperPercentEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: T2_ListBox
        function T2_ListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: T3_ListBox
        function T3_ListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Button pushed function: SaveButton_double
        function SaveButton_doublePushed(app, event)
                        
            name=app.OutputNameEditField_double.Value;

            Items = char(app.ExportFunctionListBox.Items);
            im=app.ExportFunctionListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.aa;
            end    
            if(n==2)
                data=app.vv;
            end    
            if(n==3)
                data=app.dd;
            end                

            assignin('base',name,data);

            update(app);

            msgbox('Array saved');
           
        end

        % Value changed function: T1_ListBox_cor
        function T1_ListBox_corValueChanged(app, event)
            DT1_cor(app);
        end

        % Size changed function: CorrectAccelerationTab
        function CorrectAccelerationTabSizeChanged(app, event)
           
        end

        % Button pushed function: ScriptInformationButton
        function ScriptInformationButtonPushed(app, event)
            winopen('correct_acceleration.txt');
        end

        % Button pushed function: SaveButton_cor
        function SaveButton_corPushed(app, event)
           
            name=app.OutputNameEditField_cor.Value;

            Items = char(app.ExportFunctionListBox_cor.Items);
            im=app.ExportFunctionListBox_cor.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.af;
            end    
            if(n==2)
                data=app.vf;
            end    
            if(n==3)
                data=app.df;
            end                

            assignin('base',name,data);

            update(app);

            msgbox('Array saved');
        end

        % Button pushed function: UpdateListButton_rd
        function UpdateListButton_rdPushed(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxMethod_rd
        function ListboxMethod_rdValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat_rd
        function ListboxFormat_rdValueChanged(app, event)
            Trans_change(app);
        end

        % Button pushed function: CalculateButton_rd
        function CalculateButton_rdPushed(app, event)
            ClosePlots(app);
            
            Items = char(app.ListboxMethod_rd.Items);
            im=app.ListboxMethod_rd.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat_rd.Items);
            im=app.ListboxFormat_rd.Value;
            [m]=find_index(Items,im);

            if(n==1)

                if(m==1)
                    try
                        app.FS=app.SelectInputArrayListBox_rd.Value;
                        app.THM=evalin('base',app.FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end
                else

                    try
                        app.FS=app.SelectInputArrayListBox_rd.Value;
                        F1=evalin('base',app.FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end

                    try
                        app.FS=app.SelectResponseInputArrayListBox_rd.Value;
                        F2=evalin('base',app.FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end                    
   
                    try
                        app.THM=[F1 F2(:,2)];
                    catch
                        size(F1)
                        size(F2)
                        warndlg('Arrays not compatible');
                        return;
                    end    

                end
            end    

            app.dt=mean(diff(app.THM(:,1)));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            I1=app.nhp_rd;

            Items = char(app.T2_ListBox_rd.Items);
            im=app.T2_ListBox_rd.Value;
            [I2]=find_index(Items,im);

            Items = char(app.T3_ListBox_rd.Items);
            im=app.T3_ListBox_rd.Value;
            [I3]=find_index(Items,im);        
            
            Items = char(app.UnitsListBox_cor.Items);
            im=app.UnitsListBox_cor.Value;
            [iu]=find_index(Items,im);

            Items = char(app.RelativeDisplacementListBox.Items);
            im=app.RelativeDisplacementListBox.Value;
            [iorder]=find_index(Items,im);        
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            t1=app.THM(:,1);
            t2=t1;
            a=app.THM(:,2);
            b=app.THM(:,3);

            if(iorder==1)
                ra=a-b;
            else
                ra=b-a;
            end
            
            % Integration 1
            
            if(I1==2)
                fc=str2num(app.HighPassFilterHzEditField_rd.Value);
                ra=detrend(ra);      
                [ra]=Butterworth_filter_highpass_function(ra,fc,app.dt);    
            end
            
            rv=app.dt*cumtrapz(ra);
            
            if(I2==2)
                rv=rv-mean(rv);    
            end    
            if(I2==3)
                rv=detrend(rv);        
            end 
            
            rd=app.dt*cumtrapz(rv);
            
            if(I3==2)
                rd=rd-mean(rd);    
            end    
            if(I3==3)
                rd=detrend(rd);        
            end 
            
            if(iu==1)
                rd=rd*386;
            end
            if(iu==2)
                rd=rd*9.81*1000;
            end
            if(iu==3)
                rd=rd*1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            
            if(iu<=2)
                accel_label='Accel (G)';
            else
                accel_label='Accel (m/sec^2)';    
            end 
            
            if(iu<=1)
                rel_disp_label='Rel Disp (in)';
            else
                rel_disp_label='Rel Disp (mm)'; 
            end 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            figure(fig_num);
            fig_num=fig_num+1;
            
            if(max(abs(a))>max(abs(b)))
            
                    subplot(2,1,1);
                    plot(t1,a);
                    grid on;
                    ylabel(accel_label);
                    title('Acceleration  top=first  bottom=second');
                    yLimits = get(gca,'YLim');
                    ya=max(abs(yLimits));
                    ylim([-ya ya]);
            %
                    subplot(2,1,2);
                    plot(t2,b);
                    grid on;
                    xlabel('Time (sec)');
                    ylabel(accel_label);
                    ylim([-ya ya]);    
            else    
                    subplot(2,1,2);
                    plot(t2,b);
                    grid on;
                    xlabel('Time (sec)');
                    ylabel(accel_label);
                    yLimits = get(gca,'YLim');
                    ya=max(abs(yLimits));        
                    ylim([-ya ya]);        
                    
                    subplot(2,1,1);
                    plot(t1,a);
                    grid on;
                    ylabel(accel_label);
                    title('Acceleration  top=first  bottom=second');
                    ylim([-ya ya]);
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(fig_num);
            fig_num=fig_num+1;
            
            plot(t1,rd);
            grid on;
            xlabel('Time (sec)');
            ylabel(rel_disp_label);
            title('Relative Displacement');
            yLimits = get(gca,'YLim');        
            ya=max(abs(yLimits));
            ylim([-ya ya]);
            
            rel_disp=[ t1 rd ];
            setappdata(0,'rel_disp',rel_disp);
            
            amp=rd;
            %
            n=max(size(rd));
            mu=mean(amp);
            sd=std(amp);
            mx=max(amp);
            mi=min(amp);
            rms=sqrt(sd^2+mu^2);
            %
            disp(' ');
            disp(' Relative Displacement Statistics ');
            disp(' ');
            %
            out0 = sprintf(' number of points = %d \n',n);
            out1 = sprintf(' mean = %8.4g   \nstd = %8.4g    rms = %8.4g \n',mu,sd,rms);
            out2a = sprintf(' max  = %9.4g              ',mx);
            out2b = sprintf(' min  = %9.4g            \n',mi);
            disp(out0);
            disp(out1);
            disp(out2a);
            disp(out2b);
            %
            ss=sprintf('%s \n%s \n%s ',out1,out2a,out2b);

            app.ResultsTextArea_rd.Value=ss;  
            app.ResultsTextArea_rd.Visible='on';  
            app.ResultsTextAreaLabel_rd.Visible='on';  

            name=app.OutputArrayNameEditField_rd.Value;
            assignin('base',name,rel_disp);

        end

        % Value changed function: T1_ListBox_rd
        function T1_ListBox_rdValueChanged(app, event)
            HP_rd(app);
            ClosePlots(app);
        end

        % Value changing function: HighPassFilterHzEditField_rd
        function HighPassFilterHzEditField_rdValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: T2_ListBox_rd
        function T2_ListBox_rdValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: T3_ListBox_rd
        function T3_ListBox_rdValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: RelativeDisplacementListBox
        function RelativeDisplacementListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: UnitsListBox_cor_2
        function UnitsListBox_cor_2ValueChanged(app, event)
            ClosePlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.integrate_differentiate_aver13byTomIrvineLabel = uilabel(app.UIFigure);
            app.integrate_differentiate_aver13byTomIrvineLabel.Text = 'integrate_differentiate_a    ver 1.3     by Tom Irvine       ';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.IntegrateorDifferentiateTab = uitab(app.TabGroup2);
            app.IntegrateorDifferentiateTab.Title = 'Integrate or Differentiate';
            app.TabGroup3 = uitabgroup(app.IntegrateorDifferentiateTab);
            app.IntegrateTab_3 = uitab(app.TabGroup3);
            app.IntegrateTab_3.Title = 'Integrate';
            app.CalculateButton_integrate = uibutton(app.IntegrateTab_3, 'push');
            app.CalculateButton_integrate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_integratePushed, true);
            app.CalculateButton_integrate.Text = 'Calculate';
            app.Label = uilabel(app.IntegrateTab_3);
            app.Label.Text = 'Correct Acceleration, Velocity, Displacement for Oscillation about Zero Baseline';
            app.MultiplybyScaleFactorLabel = uilabel(app.IntegrateTab_3);
            app.MultiplybyScaleFactorLabel.Text = {'Multiply by '; 'Scale Factor'};
            app.MultiplybyScaleFactorListBox = uilistbox(app.IntegrateTab_3);
            app.MultiplybyScaleFactorListBox.Items = {'No', 'Yes'};
            app.MultiplybyScaleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @MultiplybyScaleFactorListBoxValueChanged, true);
            app.MultiplybyScaleFactorListBox.Value = 'No';
            app.ScaleFactorEditFieldLabel = uilabel(app.IntegrateTab_3);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.IntegrateTab_3, 'text');
            app.ScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @ScaleFactorEditFieldValueChanging, true);
            app.InputYaxisLabelEditFieldLabel = uilabel(app.IntegrateTab_3);
            app.InputYaxisLabelEditFieldLabel.Text = 'Input Y-axis Label';
            app.InputYaxisLabelEditField = uieditfield(app.IntegrateTab_3, 'text');
            app.InputYaxisLabelEditField_2Label = uilabel(app.IntegrateTab_3);
            app.InputYaxisLabelEditField_2Label.Text = 'Output Y-axis Label';
            app.OutputYaxisLabelEditField = uieditfield(app.IntegrateTab_3, 'text');
            app.SelectTrendRemovalPriortoIntegrationLabel = uilabel(app.IntegrateTab_3);
            app.SelectTrendRemovalPriortoIntegrationLabel.Text = {'Select Trend Removal '; 'Prior to Integration'};
            app.SelectTrendRemovalPriortoIntegrationListBox = uilistbox(app.IntegrateTab_3);
            app.SelectTrendRemovalPriortoIntegrationListBox.Items = {'None', 'Mean Removal', 'First Order Trend', 'High-Pass Filter'};
            app.SelectTrendRemovalPriortoIntegrationListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTrendRemovalPriortoIntegrationListBoxValueChanged, true);
            app.SelectTrendRemovalPriortoIntegrationListBox.Value = 'None';
            app.SelectTrendRemovalAfterIntegrationListBoxLabel = uilabel(app.IntegrateTab_3);
            app.SelectTrendRemovalAfterIntegrationListBoxLabel.Text = {'Select Trend Removal '; 'After Integration'};
            app.SelectTrendRemovalAfterIntegrationListBox = uilistbox(app.IntegrateTab_3);
            app.SelectTrendRemovalAfterIntegrationListBox.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.SelectTrendRemovalAfterIntegrationListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTrendRemovalAfterIntegrationListBoxValueChanged, true);
            app.SelectTrendRemovalAfterIntegrationListBox.Value = 'None';
            app.HighPassFilterHzEditFieldLabel = uilabel(app.IntegrateTab_3);
            app.HighPassFilterHzEditFieldLabel.Text = 'High-Pass Filter (Hz)';
            app.HighPassFilterHzEditField = uieditfield(app.IntegrateTab_3, 'text');
            app.HighPassFilterHzEditField.ValueChangingFcn = createCallbackFcn(app, @HighPassFilterHzEditFieldValueChanging, true);
            app.InputYaxisLabelEditField_2Label_2 = uilabel(app.IntegrateTab_3);
            app.InputYaxisLabelEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.IntegrateTab_3, 'text');
            app.OutputArrayNameEditField.Value = 'integrated_signal';
            app.DoubleIntegrateTab_2 = uitab(app.TabGroup3);
            app.DoubleIntegrateTab_2.Title = 'Double Integrate';
            app.CalculateButton_double = uibutton(app.DoubleIntegrateTab_2, 'push');
            app.CalculateButton_double.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_doublePushed, true);
            app.CalculateButton_double.Text = 'Calculate';
            app.UnitsListBoxLabel = uilabel(app.DoubleIntegrateTab_2);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.DoubleIntegrateTab_2);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.SelectTrendRemovalPriortoIntegrationListBox_2Label = uilabel(app.DoubleIntegrateTab_2);
            app.SelectTrendRemovalPriortoIntegrationListBox_2Label.Text = {'Select Trend Removal '; 'Prior to Integration'};
            app.T1_ListBox = uilistbox(app.DoubleIntegrateTab_2);
            app.T1_ListBox.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'High-Pass Filter'};
            app.T1_ListBox.ValueChangedFcn = createCallbackFcn(app, @T1_ListBoxValueChanged, true);
            app.T1_ListBox.Value = 'None';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel = uilabel(app.DoubleIntegrateTab_2);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel.Text = {'Select Trend Removal '; 'Between Integrations'};
            app.T2_ListBox = uilistbox(app.DoubleIntegrateTab_2);
            app.T2_ListBox.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T2_ListBox.ValueChangedFcn = createCallbackFcn(app, @T2_ListBoxValueChanged, true);
            app.T2_ListBox.Value = 'None';
            app.SelectTrendRemovalAfterIntegrationListBox_3Label = uilabel(app.DoubleIntegrateTab_2);
            app.SelectTrendRemovalAfterIntegrationListBox_3Label.Text = {'Select Trend Removal '; 'After Integration'};
            app.T3_ListBox = uilistbox(app.DoubleIntegrateTab_2);
            app.T3_ListBox.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T3_ListBox.ValueChangedFcn = createCallbackFcn(app, @T3_ListBoxValueChanged, true);
            app.T3_ListBox.Value = 'None';
            app.HighPassFilterHzEditField_doubleLabel = uilabel(app.DoubleIntegrateTab_2);
            app.HighPassFilterHzEditField_doubleLabel.Text = 'High-Pass Filter (Hz)';
            app.HighPassFilterHzEditField_double = uieditfield(app.DoubleIntegrateTab_2, 'text');
            app.HighPassFilterHzEditField_double.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterHzEditField_doubleValueChanged, true);
            app.DurationTaperPercentLabel = uilabel(app.DoubleIntegrateTab_2);
            app.DurationTaperPercentLabel.Text = {'Duration Taper '; 'Percent'};
            app.DurationTaperPercentEditField = uieditfield(app.DoubleIntegrateTab_2, 'text');
            app.DurationTaperPercentEditField.ValueChangingFcn = createCallbackFcn(app, @DurationTaperPercentEditFieldValueChanging, true);
            app.DurationTaperPercentEditField.Value = '0';
            app.ExportFunctionListBoxLabel = uilabel(app.DoubleIntegrateTab_2);
            app.ExportFunctionListBoxLabel.Text = 'Export Function';
            app.ExportFunctionListBox = uilistbox(app.DoubleIntegrateTab_2);
            app.ExportFunctionListBox.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.ExportFunctionListBox.Value = 'Acceleration';
            app.SaveButton_double = uibutton(app.DoubleIntegrateTab_2, 'push');
            app.SaveButton_double.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_doublePushed, true);
            app.SaveButton_double.Text = 'Save';
            app.OutputNameEditField_doubleLabel = uilabel(app.DoubleIntegrateTab_2);
            app.OutputNameEditField_doubleLabel.Text = 'Output Name';
            app.OutputNameEditField_double = uieditfield(app.DoubleIntegrateTab_2, 'text');
            app.DifferentitateTab = uitab(app.TabGroup3);
            app.DifferentitateTab.Title = 'Differentitate';
            app.CalculateButton_differ = uibutton(app.DifferentitateTab, 'push');
            app.CalculateButton_differ.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_differPushed, true);
            app.CalculateButton_differ.Text = 'Calculate';
            app.DividebyScaleFactorListBoxLabel = uilabel(app.DifferentitateTab);
            app.DividebyScaleFactorListBoxLabel.Text = {'Divide by '; 'Scale Factor'};
            app.DividebyScaleFactorListBox = uilistbox(app.DifferentitateTab);
            app.DividebyScaleFactorListBox.Items = {'No', 'Yes'};
            app.DividebyScaleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @DividebyScaleFactorListBoxValueChanged, true);
            app.DividebyScaleFactorListBox.Value = 'No';
            app.ScaleFactorEditField_dLabel = uilabel(app.DifferentitateTab);
            app.ScaleFactorEditField_dLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField_d = uieditfield(app.DifferentitateTab, 'text');
            app.InputYaxisLabelEditField_3Label = uilabel(app.DifferentitateTab);
            app.InputYaxisLabelEditField_3Label.Text = 'Input Y-axis Label';
            app.InputYaxisLabelEditField_d = uieditfield(app.DifferentitateTab, 'text');
            app.OutputYaxisLabelEditField_2Label = uilabel(app.DifferentitateTab);
            app.OutputYaxisLabelEditField_2Label.Text = 'Output Y-axis Label';
            app.OutputYaxisLabelEditField_d = uieditfield(app.DifferentitateTab, 'text');
            app.OutputArrayNameEditField_2Label = uilabel(app.DifferentitateTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_d = uieditfield(app.DifferentitateTab, 'text');
            app.OutputArrayNameEditField_d.Value = 'differentiated_signal';
            app.Label_2 = uilabel(app.DifferentitateTab);
            app.Label_2.Text = 'This script differentiates a time history using the "five-point method"';
            app.CorrectAccelerationTab = uitab(app.TabGroup3);
            app.CorrectAccelerationTab.SizeChangedFcn = createCallbackFcn(app, @CorrectAccelerationTabSizeChanged, true);
            app.CorrectAccelerationTab.Title = 'Correct Acceleration';
            app.CalculateButton = uibutton(app.CorrectAccelerationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveButton_cor = uibutton(app.CorrectAccelerationTab, 'push');
            app.SaveButton_cor.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_corPushed, true);
            app.SaveButton_cor.Text = 'Save';
            app.UnitsListBoxLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.UnitsListBoxLabel_cor.Text = 'Units';
            app.UnitsListBox_cor = uilistbox(app.CorrectAccelerationTab);
            app.UnitsListBox_cor.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.UnitsListBox_cor.Value = 'G, in/sec, in';
            app.SelectTrendRemovalPriortoIntegrationListBox_corLabel = uilabel(app.CorrectAccelerationTab);
            app.SelectTrendRemovalPriortoIntegrationListBox_corLabel.Text = {'Select Trend Removal '; 'Prior to Integration'};
            app.T1_ListBox_cor = uilistbox(app.CorrectAccelerationTab);
            app.T1_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'High-Pass Filter'};
            app.T1_ListBox_cor.ValueChangedFcn = createCallbackFcn(app, @T1_ListBox_corValueChanged, true);
            app.T1_ListBox_cor.Value = 'None';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor.Text = {'Select Trend Removal '; 'Between Integrations'};
            app.T2_ListBox_cor = uilistbox(app.CorrectAccelerationTab);
            app.T2_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T2_ListBox_cor.Value = 'None';
            app.SelectTrendRemovalAfterIntegrationListBox_corLabel = uilabel(app.CorrectAccelerationTab);
            app.SelectTrendRemovalAfterIntegrationListBox_corLabel.Text = {'Select Trend Removal '; 'After Integration'};
            app.T3_ListBox_cor = uilistbox(app.CorrectAccelerationTab);
            app.T3_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T3_ListBox_cor.Value = 'None';
            app.HighPassFilterHzEditFieldLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.HighPassFilterHzEditFieldLabel_cor.Text = 'High-Pass Filter (Hz)';
            app.HighPassFilterHzEditField_cor = uieditfield(app.CorrectAccelerationTab, 'text');
            app.DurationTaperPercentEditFieldLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.DurationTaperPercentEditFieldLabel_cor.Text = {'Duration Taper '; 'Percent'};
            app.DurationTaperPercentEditField_cor = uieditfield(app.CorrectAccelerationTab, 'text');
            app.DurationTaperPercentEditField_cor.Value = '0';
            app.ExportFunctionListBoxLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.ExportFunctionListBoxLabel_cor.Text = 'Export Function';
            app.ExportFunctionListBox_cor = uilistbox(app.CorrectAccelerationTab);
            app.ExportFunctionListBox_cor.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.ExportFunctionListBox_cor.Value = 'Acceleration';
            app.OutputNameEditFieldLabel_cor = uilabel(app.CorrectAccelerationTab);
            app.OutputNameEditFieldLabel_cor.Text = 'Output Name';
            app.OutputNameEditField_cor = uieditfield(app.CorrectAccelerationTab, 'text');
            app.ScriptInformationButton = uibutton(app.CorrectAccelerationTab, 'push');
            app.ScriptInformationButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptInformationButtonPushed, true);
            app.ScriptInformationButton.Text = 'Script Information';
            app.ReturnButton = uibutton(app.IntegrateorDifferentiateTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.listbox_method = uilistbox(app.IntegrateorDifferentiateTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.IntegrateorDifferentiateTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.IntegrateorDifferentiateTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.IntegrateorDifferentiateTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.IntegrateorDifferentiateTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.IntegrateorDifferentiateTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = {' The input array must have two columns: '; ' time (sec) & amplitude'};
            app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab = uitab(app.TabGroup2);
            app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab.Title = 'Relative Displacement betwen Two Acceleration Time Histories';
            app.ReturnButton_rd = uibutton(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab, 'push');
            app.ReturnButton_rd.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_rdPushed, true);
            app.ReturnButton_rd.Text = 'Return';
            app.ListboxMethod_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.ListboxMethod_rd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod_rd.ValueChangedFcn = createCallbackFcn(app, @ListboxMethod_rdValueChanged, true);
            app.ListboxMethod_rd.Tag = 'listbox_method';
            app.ListboxMethod_rd.Value = 'Array Preloaded in Matlab';
            app.text1_rd2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.text1_rd2.Tag = 'text1';
            app.text1_rd2.Text = 'Select Data Input Method';
            app.ListboxFormat_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.ListboxFormat_rd.Items = {'Common array:  time(sec) & amp & amp', 'Two separate arrays, each:  time(sec) & amp'};
            app.ListboxFormat_rd.ValueChangedFcn = createCallbackFcn(app, @ListboxFormat_rdValueChanged, true);
            app.ListboxFormat_rd.Tag = 'listbox_method';
            app.ListboxFormat_rd.Value = 'Common array:  time(sec) & amp & amp';
            app.text1_rd = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.text1_rd.Tag = 'text1';
            app.text1_rd.Text = 'Select Data Input Format';
            app.UpdateListButton_rd = uibutton(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab, 'push');
            app.UpdateListButton_rd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_rdPushed, true);
            app.UpdateListButton_rd.Text = 'Update List';
            app.SelectInputArrayListBoxLabel_rd = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectInputArrayListBoxLabel_rd.Text = 'Select Input Array';
            app.SelectInputArrayListBox_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectInputArrayListBox_rd.Items = {};
            app.SelectInputArrayListBox_rd.Value = {};
            app.SelectResponseInputArrayListBoxLabel_rd = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectResponseInputArrayListBoxLabel_rd.Text = 'Select Response Input Array';
            app.SelectResponseInputArrayListBox_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectResponseInputArrayListBox_rd.Items = {};
            app.SelectResponseInputArrayListBox_rd.Value = {};
            app.CalculateButton_rd = uibutton(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab, 'push');
            app.CalculateButton_rd.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_rdPushed, true);
            app.CalculateButton_rd.Text = 'Calculate';
            app.UnitsListBoxLabel_cor_2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.UnitsListBoxLabel_cor_2.Text = 'Units';
            app.UnitsListBox_cor_2 = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.UnitsListBox_cor_2.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.UnitsListBox_cor_2.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_cor_2ValueChanged, true);
            app.UnitsListBox_cor_2.Value = 'G, in/sec, in';
            app.RelativeDisplacementListBoxLabel = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.RelativeDisplacementListBoxLabel.Text = 'Relative Displacement';
            app.RelativeDisplacementListBox = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.RelativeDisplacementListBox.Items = {'First - Second', 'Second - First'};
            app.RelativeDisplacementListBox.ValueChangedFcn = createCallbackFcn(app, @RelativeDisplacementListBoxValueChanged, true);
            app.RelativeDisplacementListBox.Value = 'First - Second';
            app.HighPassFilterHzEditFieldLabel_rd = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.HighPassFilterHzEditFieldLabel_rd.Text = 'High-Pass Filter (Hz)';
            app.HighPassFilterHzEditField_rd = uieditfield(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab, 'text');
            app.HighPassFilterHzEditField_rd.ValueChangingFcn = createCallbackFcn(app, @HighPassFilterHzEditField_rdValueChanging, true);
            app.SelectTrendRemovalPriortoIntegrationListBox_corLabel_2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectTrendRemovalPriortoIntegrationListBox_corLabel_2.Text = {'Select Trend Removal '; 'Prior to Integration'};
            app.T1_ListBox_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.T1_ListBox_rd.Items = {'None', 'High-Pass Filter'};
            app.T1_ListBox_rd.ValueChangedFcn = createCallbackFcn(app, @T1_ListBox_rdValueChanged, true);
            app.T1_ListBox_rd.Value = 'None';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2.Text = {'Select Trend Removal '; 'Between Integrations'};
            app.T2_ListBox_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.T2_ListBox_rd.Items = {'None', 'Mean Removal', 'First Order Trend'};
            app.T2_ListBox_rd.ValueChangedFcn = createCallbackFcn(app, @T2_ListBox_rdValueChanged, true);
            app.T2_ListBox_rd.Value = 'None';
            app.SelectTrendRemovalAfterIntegrationListBox_corLabel_2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.SelectTrendRemovalAfterIntegrationListBox_corLabel_2.Text = {'Select Trend Removal '; 'After Integration'};
            app.T3_ListBox_rd = uilistbox(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.T3_ListBox_rd.Items = {'None', 'Mean Removal', 'First Order Trend'};
            app.T3_ListBox_rd.ValueChangedFcn = createCallbackFcn(app, @T3_ListBox_rdValueChanged, true);
            app.T3_ListBox_rd.Value = 'None';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_rd = uieditfield(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab, 'text');
            app.OutputArrayNameEditField_rd.Value = 'relative_disp';
            app.ResultsTextAreaLabel_rd = uilabel(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            app.ResultsTextAreaLabel_rd.Text = 'Results';
            app.ResultsTextArea_rd = uitextarea(app.RelativeDisplacementbetwenTwoAccelerationTimeHistoriesTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrate_differentiate_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end