classdef iso_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        ProcessingListBox               matlab.ui.control.ListBox
        ProcessingListBoxLabel          matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        ISO2631HumanTab                 matlab.ui.container.Tab
        PercentOverlapEditField         matlab.ui.control.EditField
        PercentOverlapLabel             matlab.ui.control.Label
        SegmentDurationsecEditField     matlab.ui.control.EditField
        SegmentDurationsecEditFieldLabel  matlab.ui.control.Label
        SubdividetheDataintoSegmentsListBox  matlab.ui.control.ListBox
        SubdividetheDataintoSegmentsListBoxLabel  matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        WeightListBox                   matlab.ui.control.ListBox
        WeightListBoxLabel              matlab.ui.control.Label
        InputAmplitudeUnitListBox       matlab.ui.control.ListBox
        InputAmplitudeUnitLabel         matlab.ui.control.Label
        CalculateButton_2631            matlab.ui.control.Button
        ISO10816MachineTab              matlab.ui.container.Tab
        MaxEditField_2                  matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputFFTMagntiudeListBox       matlab.ui.control.ListBox
        OutputFFTMagntiudeListBoxLabel  matlab.ui.control.Label
        OutputAmplitudeUnitsListBox     matlab.ui.control.ListBox
        OutputAmplitudeUnitsListBoxLabel  matlab.ui.control.Label
        OutputFrequencyListBox          matlab.ui.control.ListBox
        OutputFrequencyListBoxLabel     matlab.ui.control.Label
        PlotFrequenciesHzLabel_2        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculateButton_10816           matlab.ui.control.Button
        ISOGenericCriteriaTab           matlab.ui.container.Tab
        OutputVelocitySpectrumArrayNameEditField_generic  matlab.ui.control.EditField
        OutputVelocitySpectrumArrayNameEditField_2Label  matlab.ui.control.Label
        ResultsTextArea_generic         matlab.ui.control.TextArea
        ResultsTextAreaLabel_generic    matlab.ui.control.Label
        InputAmplitudeListBox_generic   matlab.ui.control.ListBox
        InputAmplitudeListBox_2Label    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalculateButton_generic         matlab.ui.control.Button
        VelocitySpectrumdBTab           matlab.ui.container.Tab
        OutputVelocitySpectrumArrayNameEditField  matlab.ui.control.EditField
        OutputVelocitySpectrumArrayNameEditFieldLabel  matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        InputAmplitudeListBox           matlab.ui.control.ListBox
        InputAmplitudeListBoxLabel      matlab.ui.control.Label
        PlotFrequenciesHzLabel          matlab.ui.control.Label
        CalculateButton_velox           matlab.ui.control.Button
        iso_mainver10byTomIrvineLabel   matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM    % Description
        ref    %
        t1     %
        t2     %
        spl    %
        oaspl  %
        mr     %
        window %
        iflag  %
        dt     %
        acceleration_FFT %
        velox_FFT %
        disp_FFT  %
        nsub   %
    end
    
    methods (Access = private)

        function signal(app)
            value = app.ProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';            
            end              
        end        

        function preprocess(app)

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            ntime=app.ProcessingListBox.Value;
            
            if(contains(ntime,'Entire'))
                app.t1=app.THM(1,1);
                app.t2=app.THM(end,1);
            else    
                app.t1=str2num(app.StartTimesecEditField.Value);
                app.t2=str2num(app.EndTimesecEditField.Value);
                [~,i]=min(abs(app.t1-app.THM));
                [~,j]=min(abs(app.t2-app.THM));
                app.THM=app.THM(i:j,:);          
            end  
         
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            if(app.iflag==999)
                return;
            end                  

            app.dt=mean(diff(app.THM(:,1)));
        end    
        
        function update(app)
            ClosePlots(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end

        function subdivide(app)
            Items = char(app.SubdividetheDataintoSegmentsListBox.Items);
            im=app.SubdividetheDataintoSegmentsListBox.Value;
            [app.nsub]=find_index(Items,im);

            if(app.nsub==1)
                app.SegmentDurationsecEditField.Visible='on';
                app.SegmentDurationsecEditFieldLabel.Visible='on';
                app.PercentOverlapEditField.Visible='on';
                app.PercentOverlapLabel.Visible='on';
            else    
                app.SegmentDurationsecEditField.Visible='off';
                app.SegmentDurationsecEditFieldLabel.Visible='off';
                app.PercentOverlapEditField.Visible='off';
                app.PercentOverlapLabel.Visible='off';            
            end

        end    

        function ClosePlots(app)

            app.OutputFFTMagntiudeListBox.Visible='off'; 
            app.OutputArrayNameEditField.Visible='off';             
            app.OutputFFTMagntiudeListBoxLabel.Visible='off'; 
            app.OutputArrayNameEditFieldLabel.Visible='off'; 
            app.SaveButton.Visible='off'; 

            app.ResultsTextArea_generic.Value='';
            app.ResultsTextArea_generic.Visible='off';     
            app.ResultsTextAreaLabel_generic.Visible='off';      

            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            signal(app);
            subdivide(app);
            ClosePlots(app);     
            app.mr=1;      % mean removal
            app.window=2;  % Hanning window
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_2631
        function CalculateButton_2631Pushed(app, event)
            ClosePlots(app);
            preprocess(app);

            amp=detrend(app.THM(:,2)); 
            t=app.THM(:,1);
            dur=t(end)-t(1);

            Items = char(app.InputAmplitudeUnitListBox.Items);
            im=app.InputAmplitudeUnitListBox.Value;
            [iu]=find_index(Items,im);  

            Items = char(app.WeightListBox.Items);
            im=app.WeightListBox.Value;
            [iweight]=find_index(Items,im);            

            Items = char(app.SubdividetheDataintoSegmentsListBox.Items);
            im=app.SubdividetheDataintoSegmentsListBox.Value;
            [ig]=find_index(Items,im); 

            sr=1/app.dt;

            if(iu==1)
                amp=amp*9.81;
            end

            %
            [fwl,fw,fwu,wk,wd,wf,wc,we,wj,wb,wm,www]=weight_factors_alt(iweight);
            %
            aw=zeros(length(amp),1);
            %
            iband=3;  % bandpass filtering
            iphase=1; % refiltering for phase correction

            nbands=length(www);

            rms=zeros(nbands,1);

            fper=0.01;

            [yy]=vibrationdata_half_cosine_fade(amp,fper);  

            progressbar;

            for i=1:nbands
            
                progressbar(i/nbands);
         
                fh=fwl(i);  % highpass filter frequency
                fl=fwu(i);  % lowpass filter frequency

                if(fh>(1/dur) && fl<(sr/5) && www(i)>3.5e-05)    

                    [yyy,~,~,rms(i)]=...
                        Butterworth_filter_function_alt(yy,app.dt,iband,fl,fh,iphase);

                    aw=aw+yyy*www(i);        
                end

            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  VDV is calculated over the whole signal regardless of whether 
            %      the data is divided into segments
            %
            
            pause(0.5);
            progressbar(1);
            
            n=length(aw);
            %
            AW=std(aw);
            MTW=AW;
            %
            VDV=0;
            for i=1:n
                VDV=VDV+aw(i)^4;
            end
            VDV=(VDV*app.dt)^0.25;
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp('* * * * * * * * * *');
            disp(' ');

            if(iweight==1)
                tstring='Wk Weighted Time History';
            end
            if(iweight==2)
                tstring='Wd Weighted Time History';
            end
            if(iweight==3)
                tstring='Wf Weighted Time History';
            end    
            if(iweight==4)
                tstring='Wc Weighted Time History';
            end
            if(iweight==5)
                tstring='We Weighted Time History';
            end
            if(iweight==6)
                tstring='Wj Weighted Time History';
            end    
            if(iweight==7)
                tstring='Wb Weighted Time History';
            end
            if(iweight==8)
                tstring='Wm Weighted Time History';
            end    
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   

            LT=length(t);

            if(ig==1)  % segments
        
                disp(' ');
                disp('  Time         aw            aw    ');
                disp(' (sec)     (m/sec^2) RMS   (GRMS) ');
            
                seg=str2num(app.SegmentDurationsecEditField.Value);
                p=str2num(app.PercentOverlapEditField.Value);
                
                ns=fix(sr*seg); 
                po=p/100;
            %
                step=floor(ns*(1-po));

              % fprintf('\n ns=%d  step=%d \n',ns,step);
                
                i=1;
                j1=1;
                j2=1+ns;
            
                while(1)
            
                    if((j2)>LT)
                        break;
                    end
                
                    j1=j1+step;
                    j2=j2+step;
                
                    i=i+1;
                
                    if(i>500000)
                        break;
                    end    
                
                end 
            
                nnn=i;
                
                if(nnn==0)
                    warndlg('nnn=0');
                    return;
                end
                if(step==0)
                    warndlg('step=0');
                    return;
                end
                if(nnn>500000)
                    warndlg('nnn>500000');
                    return;
                end
            
                as=zeros(nnn,1);
                tt=zeros(nnn,1);
            %
                j1=1;
                j2=1+ns;
                
                ijk=1;
            
                for i=1:nnn
                    if(j2>length(t))
                        break;
                    end
            %
                    clear x;
                    x=aw(j1:j2);
                  
                    if(isempty(x))
                        break;
                    end
                    
                    tt(i)=(t(j1)+t(j2))/2;     
                    as(i)=std(x);
                    
                    fprintf('%8.2f   %8.2f     %8.3f \n',tt(i),as(i),as(i)/9.81);
                    
                    ttt(ijk)=tt(i);
                    aas(ijk)=as(i);
                     
                    if(as(i)>MTW)
                        MTW=as(i);
                    end
            %
                    j1=j1+step;
                    j2=j2+step;
            % 
                    ijk=ijk+1;
                end    
                
                if(ig==1)
                    tstring=sprintf(' %s \n %g sec segments, %g%% overlap  ',tstring,seg,100*po);
                end
                
                ttt=fix_size(ttt);
                aas=fix_size(aas);
                
                figure(1);
                plot(app.THM(:,1),app.THM(:,2));
                title('Input Time History');
                if(iu==1)
                    ylabel('Accel (G)');         
                else
                    ylabel('Accel (m/sec^2)');        
                end
                xlabel('Time (sec)');
                grid on;
            
                figure(2);
                plot(ttt,aas);
                ylabel('Accel (m/sec^2) RMS');
                xlabel('Time (sec)');
                grid on;
                title(tstring);
                yabs=max(abs(get(gca,'ylim')));
                [ymax,yTT,ytt,app.iflag]=ytick_linear_one_sided(yabs);
                if(app.iflag==1)
                    ylim([0,ymax]);    
                    set(gca,'ytick',ytt);
                    set(gca,'YTickLabel',yTT);  
                    set(gca,'MinorGridLineStyle',':','GridLineStyle',':',...
                                                 'XminorTick','off','YminorTick','on'); 
                end
                    
                figure(3);
                plot(ttt,aas/9.81);
                ylabel('Accel (GRMS)');
                xlabel('Time (sec)');
                grid on;
                title(tstring);   
                yabs=max(abs(get(gca,'ylim')));    
                [ymax,yTT,ytt,app.iflag]=ytick_linear_one_sided(yabs);
                if(app.iflag==1)
                    ylim([0,ymax]);    
                    set(gca,'ytick',ytt);
                    set(gca,'YTickLabel',yTT);        
                    set(gca,'MinorGridLineStyle',':','GridLineStyle',':',...
                                                 'XminorTick','off','YminorTick','on');         
                end   
                
            else
                
                figure(2);
                plot(tim,aw);
                ylabel('Accel (m/sec^2) RMS');
                xlabel('Time (sec)');
                grid on;
                title(tstring);     
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            out1=sprintf('Composite Weighted Level  AW = %7.3g (m/sec^2)RMS ',AW);
            out2=sprintf('Maximum Transient Vibration MTW = %7.3g (m/sec^2)RMS ',MTW);
            out3=sprintf('Fourth Power Vibration Dose VDV = %7.3g (m/sec^(1.75))',VDV);
            out4=sprintf('\n                          MTW/AW = %7.3g',MTW/AW);
            
            ss=sprintf('%s \n\n%s \n\n%s \n%s ',out1,out2,out3,out4);    

            if(ig==1)
                
                name1='accel_mpss_rms';
                name2='accel_grms';
                   
                assignin('base', name1, [ttt aas]);    
                assignin('base', name2, [ttt aas/9.81]);     
            
                name1=sprintf('   %s',name1);
                name2=sprintf('   %s',name2);
                
                ss=sprintf('%s \n\n Output Arrays - time & weighted amplitude RMS \n %s   & %s',ss,name1,name2);

                %%%%%%%%%
            
                aw=fix_size(aw);
            
                name11='aw_accel_mpss';
                name22='aw_accel_g';
                   
                assignin('base', name11, [t aw]);    
                assignin('base', name22, [t aw/9.81]);     
            
                name1=sprintf('   %s',name11);
                name2=sprintf('   %s',name22);
                
                ss=sprintf('%s \n\n Output Arrays - time & weighted accel \n %s   & %s',ss,name1,name2);
            
            end                

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button pushed function: CalculateButton_velox
        function CalculateButton_veloxPushed(app, event)
            ClosePlots(app)
            preprocess(app);
   
            fig_num=1;

            iamp=app.InputAmplitudeListBox.Value;

            y=detrend(app.THM(:,2));     
            
            if(contains(iamp,'Accel'))
                y=y*386;
                y=app.dt*cumtrapz(y);
                y=detrend(y);
            end         
            
            app.THM(:,2)=y;
            amp=y;

            app.ref=1e-06;  % 1 micro in/sec

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            t=app.THM(:,1);
            n=length(t);
            
            dur=t(end)-t(1);
            nj=floor(log2(n));
            
            njt=min([12 nj]);
            
                num_seg=zeros(njt,1);
               time_seg=zeros(njt,1);
            samples_seg=zeros(njt,1);
                    ddf=zeros(njt,1);
            
            for i=1:njt
                num_seg(i)=2^(i-1);
                time_seg(i)=dur/num_seg(i);
                samples_seg(i)=floor(n/num_seg(i));
                ddf(i)=1/time_seg(i);
            end    
            
            [~,nv]=min(abs( ddf-1));
            
            df=ddf(nv);
            spl_core_alt
            NW=num_seg(nv);
            mmm=samples_seg(nv);
            
            fprintf('\n NW=%d  statdof=%d  df=%7.3g Hz  \n\n',NW,NW*2,df);
            
            %%%%
            
            [app.spl,app.oaspl]=spl_core_alt_no(mmm,NW,app.mr,app.window,amp,df,app.ref);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            pf=app.spl(:,1);
            pspl=app.spl(:,2);
            
            %
            iwn=1;  % no weighting
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %       
            fprintf('\n Overall = %7.3g dB  ref 20 micro in/sec \n\n',app.oaspl);
            %
            if(iwn==1)
                p_rms = app.ref*(10^(app.oaspl/20.));
                fprintf('  Overall Velocity = %7.3g in/sec rms  \n\n',p_rms);
            end
            %
                figure(fig_num);
                fig_num=fig_num+1;
                plot(pf,pspl);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %
                out7=sprintf('One-Third Octave Velocity Spectrum   Overall=%7.4g dB \n Ref= 20 micro in/sec',app.oaspl); 
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
                
                fmin=app.MinEditField.Value;
                fmax=app.MaxEditField.Value;
                xlim([fmin,fmax]);
                
                [xtt,xTT,app.iflag]=xtick_label(fmin,fmax);
            
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);    
                end   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
          
             nnn=length(pf);

             for i=nnn:-1:1
                if(pf(i)>fmax || pf(i)<fmin)
                    pf(i)=[];
                    pspl(i)=[];
                end    
             end    

             pf=fix_size(pf);
             pspl=fix_size(pspl);
             SPL=[pf pspl];             

            %%
                a(1)=1;
                a(2)=2;
                a(3)=4;
                a(4)=8; 
                a(5)=16;
                a(6)=31.5;
                a(7)=63;
                a(8)=125;
                a(9)=250;
                a(10)=500;
                a(11)=1000;
                a(12)=2000;
                a(13)=4000;
                a(14)=8000;
                a(15)=16000;
            %
                clear f;
            %
                bn=zeros(length(pspl),1);
                
                for i=1:length(pspl)
                    bn(i)=i;
                    f{i}=' ';
                    
                    for j=1:15
                            
                        if( (abs(pf(i)-a(j))/a(j)) < 0.05 )
                                            
                            if(j==11)
                                f{i}='1K';
                                break;
                            end
                            if(j==12)
                                f{i}='2K';
                                break;
                            end
                            if(j==13)
                                f{i}='4K';
                                break;
                            end
                            if(j==14)
                                f{i}='8K';
                                break;
                            end
                            if(j==15)
                                f{i}='16K';
                                break;
                            end               
                            f{i}=sprintf('%g',a(j));
                            break;
                        end    
              
                    end    
                       
                end
            %
                f=fix_size(f);
            %
                cpf(1)=pf(1);
                oct=2^(1/3);
                for i=2:length(pf)
                    cpf(i)=cpf(i-1)*oct;
                end
            %
                figure(fig_num);
                fig_num=fig_num+1;
                xx=log10(cpf);
                width=0.7;
                bar(xx,pspl,width);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');
                set(gca,'Xtick',xx)
                set(gca,'XTickLabel',f)
            %
                minp=min(pspl);
                maxp=max(pspl);
            %
                for i=0:20
                    b=i*10;
                    if(b>minp)
                        ymin=b-10;
                        break;
                    end    
                end
            %
                for i=0:20
                    b=i*10;
                    if(b>maxp)
                        ymax=b;
                        break;
                    end    
                end
            %
                fmin=log10(min(pf)/1.2599);
                fmax=log10(max(pf)*1.2599);
            %
                xlim([fmin,fmax]);
                try
                    ylim([ymin,ymax]);
                catch
                end
            %  
            try
                name=app.OutputVelocitySpectrumArrayNameEditField.Value;
                assignin('base',name, SPL);                
            catch
            end            

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);            
        end

        % Button pushed function: CalculateButton_10816
        function CalculateButton_10816Pushed(app, event)
            clear app.velox;
            clear app.disp;
            clear app.acceleration;
            
            ClosePlots(app);
            preprocess(app);

            y=detrend(app.THM(:,2)); 
            t=app.THM(:,1);
            amp=y;

            fmax=str2num(app.MaxEditField_2.Value);
            fmin=str2num(app.MinEditField_2.Value);

            Items = char(app.OutputFrequencyListBox.Items);
            im=app.OutputFrequencyListBox.Value;
            [ifru]=find_index(Items,im);
            
            Items = char(app.OutputAmplitudeUnitsListBox.Items);
            im=app.OutputAmplitudeUnitsListBox.Value;
            [iu]=find_index(Items,im);

            %%%
           
            fig_num=1;
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,amp);
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            title('Time History');
            
            n=length(t);
            N=2^floor(log(n)/log(2));
            
            %%%%%%%%%%%%%%
            
            [freq,full,~,~]=full_FFT_core(app.mr,app.window,amp,N,app.dt);
            
            %
            if(ifru==2)
                freq=freq*60;
            end
            %
                clear accel_FFT;
                accel_FFT=[freq full];
                app.velox_FFT=accel_FFT;
                 app.disp_FFT=accel_FFT;
            %
                n=length(freq);
            %    
                for i=1:n
                    omegan=2*pi*freq(i);
                    app.velox_FFT(i,2)= app.velox_FFT(i,2)/omegan;  
                     app.disp_FFT(i,2)=2*app.disp_FFT(i,2)/omegan^2;    
                end
            %
                if(iu==1)
                    app.velox_FFT(:,2)= 386*app.velox_FFT(:,2);    
                     app.disp_FFT(:,2)= 386*app.disp_FFT(:,2);         
                else
                    app.velox_FFT(:,2)= 9.81*1000*app.velox_FFT(:,2);    
                     app.disp_FFT(:,2)= 9.81*1000*app.disp_FFT(:,2);         
                end
            %
                ff=freq;
                for i=n:-1:1
                    if(ff(i)<1.0)
                        ff(i)=[];
                        app.velox_FFT(i,:)=[];
                        app.disp_FFT(i,:)=[];
                    end
                end
            %
                if(ifru==1)
                   xplabel=sprintf('Frequency (Hz)');       
                else    
                   xplabel=sprintf('Frequency (cycles/min)');       
                end
            %
            %%%%%%%%%%%%%%
            
                freq=fix_size(freq);
                full=fix_size(full);
            
                figure(fig_num);
                fig_num=fig_num+1;
                plot(freq,full)
                app.acceleration_FFT=[freq full];
                ylabel(' Accel (G)');
                xlabel(xplabel);
                grid on; 
                zoom on;
                [y1,y2,RMS]=find_log_plot_limits(fmin,fmax,accel_FFT);   
                axis([fmin fmax  y1 y2]);
                out4=sprintf(' Acceleration FFT Magnitude   Overall %7.3g GRMS',RMS);
                title(out4);   
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','log');    
            %
                figure(fig_num);
                fig_num=fig_num+1;
                plot(ff,app.velox_FFT(:,2))
                if(iu==1)
                    ylabel(' Velocity (in/sec)');
                else
                    ylabel(' Velocity (mm/sec)');        
                end
                xlabel(xplabel);
                grid on; 
                zoom on;
                [y1,y2,RMS]=find_log_plot_limits(fmin,fmax,app.velox_FFT);    
                axis([fmin fmax y1 y2]);
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','log');
            %
                disp(' ');
                disp(' Overall velocity RMS within plotted frequency limits: ');
                if(iu==1)
                   out1=sprintf('  %7.3g in/sec RMS  ',RMS); 
                   out4=sprintf(' Velocity FFT Magnitude   Overall %7.3g in/sec RMS',RMS); 
                else
                   out1=sprintf('  %7.43 mm/sec RMS  ',RMS);  
                   out4=sprintf(' Velocity FFT Magnitude   Overall %7.3g mm/sec RMS',RMS);        
                end
                disp(out1);
                title(out4);
            %
                figure(fig_num);
                fig_num=fig_num+1;
                plot(ff,app.disp_FFT(:,2));
                [y1,y2,~]=find_log_plot_limits(fmin,fmax,app.disp_FFT);
                axis([fmin fmax  y1 y2]);  
                dFF=[app.disp_FFT(:,1) 0.5*app.disp_FFT(:,2)];
                [~,~,RMS]=find_log_plot_limits(fmin,fmax,dFF);
                if(iu==1)
                    ylabel(' Displacement (in pk-pk)');
                    out4=sprintf(' Displacement FFT Magnitude   Overall %7.3g in RMS',RMS);         
                else
                    ylabel(' Displacement (mm pk-pk)');
                    out4=sprintf(' Displacement FFT Magnitude   Overall %7.3g mm RMS',RMS);        
                end
                xlabel(xplabel);
                title(out4);
                grid on; 
                zoom on;
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','log');
            %
            %%%%%%%%%%%%%%

            app.OutputFFTMagntiudeListBox.Visible='on'; 
            app.OutputArrayNameEditField.Visible='on';             
            app.OutputFFTMagntiudeListBoxLabel.Visible='on'; 
            app.OutputArrayNameEditFieldLabel.Visible='on'; 
            app.SaveButton.Visible='on';             


        end

        % Button pushed function: CalculateButton_generic
        function CalculateButton_genericPushed(app, event)
            ClosePlots(app);
            preprocess(app);
            
            fig_num=1;

            Items = char(app.InputAmplitudeListBox_generic.Items);
            im=app.InputAmplitudeListBox_generic.Value;
            [n]=find_index(Items,im);

            y=detrend(app.THM(:,2));  
            iband=2;  % highpass filter 
            iphase=1; % refiltering for phase correction
            fl=2;
            fh=2;
            [y,~,~,~]=Butterworth_filter_function_alt(y,app.dt,iband,fl,fh,iphase);          
            
            if(n<=2)
                if(n==1) 
                    y=y*386;
                end
                y=app.dt*cumtrapz(y);
                y=detrend(y);
            end         
            
            if(n==3)
                y=y*0.0254; % convert in/sec to m/sec
            end    

            amp=y*1.0e+06;  % convert to micro meters/sec

            app.ref=1;

            app.THM=[app.THM(:,1) amp];

            p_unit=sprintf('micro meters/sec');
            x_label=sprintf('Time (sec)');
            y_label=sprintf('Velocity (%s)',p_unit);
            t_string=sprintf('Time History');
            [fig_num]=plot_TH(fig_num,x_label,y_label,t_string,app.THM);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            t=app.THM(:,1);
            n=length(t);
            
            dur=t(end)-t(1);
            nj=floor(log2(n));
            
            njt=min([12 nj]);
            
                num_seg=zeros(njt,1);
               time_seg=zeros(njt,1);
            samples_seg=zeros(njt,1);
                    ddf=zeros(njt,1);
            
            for i=1:njt
                num_seg(i)=2^(i-1);
                time_seg(i)=dur/num_seg(i);
                samples_seg(i)=floor(n/num_seg(i));
                ddf(i)=1/time_seg(i);
            end    
            
            [~,nv]=min(abs( ddf-1));
            
            df=ddf(nv);
            
            NW=num_seg(nv);
            mmm=samples_seg(nv);
            
            fprintf('\n NW=%d  statdof=%d  df=%7.3g Hz  \n\n',NW,NW*2,df);
            
            %%%%
            
            [app.spl,app.oaspl]=spl_core_alt_no(mmm,NW,app.mr,app.window,amp,df,app.ref);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            pf=app.spl(:,1);
            pspl=app.spl(:,2);
            
            fmin=4;
            fmax=80;

            nnn=length(pf);

            for i=nnn:-1:1
                if(pf(i)>fmax || pf(i)<fmin)
                    pf(i)=[];
                    pspl(i)=[];
                end    
            end    

            pf=fix_size(pf);
            pspl=fix_size(pspl);

            nnn=length(pf);

            vv=zeros(nnn,1);

            for i=1:nnn
                vv(i)=app.ref*10^(pspl(i)/20);
            end

            pv=vv;

            %%%%

            h=figure(fig_num);
            fig_num=fig_num+1;
            fs=[4 8 80]';
            ws=[1600 800 800]';
            of=0.5*ws;
            res=0.5*of;
            th=0.5*res;
            vca=0.5*th;
            vcb=0.5*vca;
            vcc=0.5*vcb;
            vcd=0.5*vcc;
            vce=0.5*vcd;
            
            n=size(pf);
        
            for i=n:-1:1
                if(pv(i)<1.0e-20)
                    pv(i)=[];
                    pf(i)=[];
                end
            end  
            
            plot(pf,pv,fs,ws,'--k',fs,of,'--k',fs,res,'--k',fs,th,'--k',fs,vca,'--k',...
                                      fs,vcb,'--k',fs,vcc,'--k',fs,vcd,'--k',fs,vce,'--k');
            title('One Third Octave Band Spectral Velocity'); 
            ylabel('Velocity (micro meters/sec)');
            xlabel('Center Frequency (Hz)');
        %
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale',...
                                                             'log','YScale','log');
            fmin=4;
            fmax=80;
        %    
            yyy=max(pv);
            if(yyy<1600)
                yyy=1600;
            end
        %    
            yss=min(pv);
            if(yss>min(vce))
                yss=min(vce);
            end
        %
        %%    ymax=10^ceil(log10(max(yyy)));
            ymax=3200;
        %%    ymin=10^floor(log10(min(yss)));
            ymin=3.125;
            axis([fmin,fmax,ymin,ymax]);
        %
            set(gca,'ytick',[3.125 6.25 12.5 25 50 100 200 400 800 1600 3200])
            set(gca,'YTickLabel',{'3.125';'6.25';'12.5';'25';'50';'100';'200';'400';'800';'1600';'3200'}) 
        %
            set(gca,'xtick',[4 5 6.3 8 10 12.5 16 20 25 31.5 40 50 63 80])
            set(gca,'XTickLabel',{'4';'5';'6.3';'8';'10';'12.5';'16';'20';'25';'31.5';'40';'50';'63';'80';})    
        %
            text('Position',[81 800],'String','Workshop','color','k','FontWeight','normal','FontSize',9) 
            text('Position',[81 400],'String','Office','color','k','FontWeight','normal','FontSize',9)
            text('Position',[81 200],'String','Residential','color','k','FontWeight','normal','FontSize',9)
            text('Position',[81 100],'String','Theater','color','k','FontWeight','normal','FontSize',9) 
            text('Position',[81 50],'String','VC-A','color','k','FontWeight','normal','FontSize',9) 
            text('Position',[81 25],'String','VC-B','color','k','FontWeight','normal','FontSize',9)
            text('Position',[81 12.5],'String','VC-C','color','k','FontWeight','normal','FontSize',9)     
            text('Position',[81 6.25],'String','VC-D','color','k','FontWeight','normal','FontSize',9) 
            text('Position',[81 3.125],'String','VC-E','color','k','FontWeight','normal','FontSize',9)   
        %
            % pos = get(gca,'position'); % This is in normalized coordinates 
            % pos(3:4)=pos(3:4)*.92; % Shrink the axis by a factor of .92 
            % pos(1:2)=pos(1:2)+pos(3:4)*.05; % Center it in the figure window 
            % set(gca,'position',pos);

            set(h, 'Position', [0 0 800 600]);

            %%%%

            vv=[pf vv];

            [xmax,ymax]=find_max(vv); 
            ss=sprintf(' maximum: \n\n %8.5g micro meters/sec  at %g Hz ',xmax,ymax);
     
            app.ResultsTextArea_generic.Value=ss;
            app.ResultsTextAreaLabel_generic.Visible='on';
            app.ResultsTextArea_generic.Visible='on';

            try
                name=app.OutputVelocitySpectrumArrayNameEditField_generic.Value;
                assignin('base',name, vv);                
            catch
            end       

        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Value changed function: ProcessingListBox
        function ProcessingListBoxValueChanged(app, event)
            signal(app);
        end

        % Value changed function: InputAmplitudeListBox_generic
        function InputAmplitudeListBox_genericValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: OutputFrequencyListBox
        function OutputFrequencyListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: OutputAmplitudeUnitsListBox
        function OutputAmplitudeUnitsListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinEditField_2
        function MinEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxEditField_2
        function MaxEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = char(app.OutputFFTMagntiudeListBox.Items);
            im=app.OutputFFTMagntiudeListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.acceleration_FFT;
            end    
            if(n==2)
                data=app.velox_FFT;
            end   
            if(n==3)
                data=app.disp_FFT;
            end             
            
            try
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name, data);
                msgbox('Array saved');
            catch
                warndlg('Save failed');
            end
        end

        % Value changed function: InputAmplitudeUnitListBox
        function InputAmplitudeUnitListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changed function: SubdividetheDataintoSegmentsListBox
        function SubdividetheDataintoSegmentsListBoxValueChanged(app, event)
            ClosePlots(app);
            subdivide(app);
        end

        % Value changing function: SegmentDurationsecEditField
        function SegmentDurationsecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: PercentOverlapEditField
        function PercentOverlapEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: WeightListBox
        function WeightListBoxValueChanged(app, event)
            ClosePlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.iso_mainver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.iso_mainver10byTomIrvineLabel.Text = 'iso_main    ver 1.1   by Tom Irvine         The input array must have two columns: time(sec) & amplitude.';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ISO2631HumanTab = uitab(app.TabGroup);
            app.ISO2631HumanTab.Title = 'ISO 2631 Human';
            app.CalculateButton_2631 = uibutton(app.ISO2631HumanTab, 'push');
            app.CalculateButton_2631.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2631Pushed, true);
            app.CalculateButton_2631.Text = 'Calculate';
            app.InputAmplitudeUnitLabel = uilabel(app.ISO2631HumanTab);
            app.InputAmplitudeUnitLabel.Text = {'Input Amplitude '; 'Unit'};
            app.InputAmplitudeUnitListBox = uilistbox(app.ISO2631HumanTab);
            app.InputAmplitudeUnitListBox.Items = {'G      ', 'm/sec^2'};
            app.InputAmplitudeUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InputAmplitudeUnitListBoxValueChanged, true);
            app.InputAmplitudeUnitListBox.Value = 'G      ';
            app.WeightListBoxLabel = uilabel(app.ISO2631HumanTab);
            app.WeightListBoxLabel.Text = 'Weight';
            app.WeightListBox = uilistbox(app.ISO2631HumanTab);
            app.WeightListBox.Items = {'Wk, Z-axis, Seat     ', 'Wd, X or Y-axis, Seat', 'Wf                   ', 'Wc                   ', 'We                   ', 'Wj                   ', 'Wb                   ', 'Wm, Building'};
            app.WeightListBox.ValueChangedFcn = createCallbackFcn(app, @WeightListBoxValueChanged, true);
            app.WeightListBox.Value = 'Wk, Z-axis, Seat     ';
            app.ResultsTextAreaLabel = uilabel(app.ISO2631HumanTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ISO2631HumanTab);
            app.SubdividetheDataintoSegmentsListBoxLabel = uilabel(app.ISO2631HumanTab);
            app.SubdividetheDataintoSegmentsListBoxLabel.Text = {'Subdivide the Data '; 'into Segments'};
            app.SubdividetheDataintoSegmentsListBox = uilistbox(app.ISO2631HumanTab);
            app.SubdividetheDataintoSegmentsListBox.Items = {'Yes', 'No'};
            app.SubdividetheDataintoSegmentsListBox.ValueChangedFcn = createCallbackFcn(app, @SubdividetheDataintoSegmentsListBoxValueChanged, true);
            app.SubdividetheDataintoSegmentsListBox.Value = 'Yes';
            app.SegmentDurationsecEditFieldLabel = uilabel(app.ISO2631HumanTab);
            app.SegmentDurationsecEditFieldLabel.Text = {'Segment '; 'Duration (sec)'};
            app.SegmentDurationsecEditField = uieditfield(app.ISO2631HumanTab, 'text');
            app.SegmentDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @SegmentDurationsecEditFieldValueChanging, true);
            app.PercentOverlapLabel = uilabel(app.ISO2631HumanTab);
            app.PercentOverlapLabel.Text = {'Percent '; 'Overlap'};
            app.PercentOverlapEditField = uieditfield(app.ISO2631HumanTab, 'text');
            app.PercentOverlapEditField.ValueChangingFcn = createCallbackFcn(app, @PercentOverlapEditFieldValueChanging, true);
            app.PercentOverlapEditField.Value = '50';
            app.ISO10816MachineTab = uitab(app.TabGroup);
            app.ISO10816MachineTab.Title = 'ISO 10816 Machine';
            app.CalculateButton_10816 = uibutton(app.ISO10816MachineTab, 'push');
            app.CalculateButton_10816.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_10816Pushed, true);
            app.CalculateButton_10816.Text = 'Calculate';
            app.Label_2 = uilabel(app.ISO10816MachineTab);
            app.Label_2.Text = {'This script calculates the Fast Fourier Transform (FFT) of an acceleration time history.  '; ''; 'The input array must have two columns: time(sec) & accel (G)  '; ''; 'The results are given in terms of acceleration, velocity & displacement.   See ISO 10816.'};
            app.PlotFrequenciesHzLabel_2 = uilabel(app.ISO10816MachineTab);
            app.PlotFrequenciesHzLabel_2.Text = 'Plot Frequencies (Hz)';
            app.OutputFrequencyListBoxLabel = uilabel(app.ISO10816MachineTab);
            app.OutputFrequencyListBoxLabel.Text = 'Output Frequency';
            app.OutputFrequencyListBox = uilistbox(app.ISO10816MachineTab);
            app.OutputFrequencyListBox.Items = {'Hz', 'cycles/min'};
            app.OutputFrequencyListBox.ValueChangedFcn = createCallbackFcn(app, @OutputFrequencyListBoxValueChanged, true);
            app.OutputFrequencyListBox.Value = 'Hz';
            app.OutputAmplitudeUnitsListBoxLabel = uilabel(app.ISO10816MachineTab);
            app.OutputAmplitudeUnitsListBoxLabel.Text = 'Output Amplitude Units';
            app.OutputAmplitudeUnitsListBox = uilistbox(app.ISO10816MachineTab);
            app.OutputAmplitudeUnitsListBox.Items = {'in/sec  &  inch', 'mm/sec  &  mm'};
            app.OutputAmplitudeUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @OutputAmplitudeUnitsListBoxValueChanged, true);
            app.OutputAmplitudeUnitsListBox.Value = 'in/sec  &  inch';
            app.OutputFFTMagntiudeListBoxLabel = uilabel(app.ISO10816MachineTab);
            app.OutputFFTMagntiudeListBoxLabel.Text = 'Output FFT Magntiude';
            app.OutputFFTMagntiudeListBox = uilistbox(app.ISO10816MachineTab);
            app.OutputFFTMagntiudeListBox.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.OutputFFTMagntiudeListBox.Value = 'Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ISO10816MachineTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ISO10816MachineTab, 'text');
            app.SaveButton = uibutton(app.ISO10816MachineTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MinEditField_2Label = uilabel(app.ISO10816MachineTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.ISO10816MachineTab, 'text');
            app.MinEditField_2.ValueChangingFcn = createCallbackFcn(app, @MinEditField_2ValueChanging, true);
            app.MaxEditField_2Label = uilabel(app.ISO10816MachineTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.ISO10816MachineTab, 'text');
            app.MaxEditField_2.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_2ValueChanging, true);
            app.ISOGenericCriteriaTab = uitab(app.TabGroup);
            app.ISOGenericCriteriaTab.Title = 'ISO Generic Criteria';
            app.CalculateButton_generic = uibutton(app.ISOGenericCriteriaTab, 'push');
            app.CalculateButton_generic.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_genericPushed, true);
            app.CalculateButton_generic.Text = 'Calculate';
            app.Label = uilabel(app.ISOGenericCriteriaTab);
            app.Label.Text = {'This script calculates vibration criterion (VC) curves from floor vibration data for semiconductor facilities, '; 'laboratories, theaters, workshops, etc.'};
            app.InputAmplitudeListBox_2Label = uilabel(app.ISOGenericCriteriaTab);
            app.InputAmplitudeListBox_2Label.Text = 'Input Amplitude';
            app.InputAmplitudeListBox_generic = uilistbox(app.ISOGenericCriteriaTab);
            app.InputAmplitudeListBox_generic.Items = {'Acceleration (G)', 'Acceleration (m/sec^2)', 'Velocity (in/sec)', 'Velocity (m/sec)'};
            app.InputAmplitudeListBox_generic.ValueChangedFcn = createCallbackFcn(app, @InputAmplitudeListBox_genericValueChanged, true);
            app.InputAmplitudeListBox_generic.Value = 'Acceleration (G)';
            app.ResultsTextAreaLabel_generic = uilabel(app.ISOGenericCriteriaTab);
            app.ResultsTextAreaLabel_generic.Text = 'Results';
            app.ResultsTextArea_generic = uitextarea(app.ISOGenericCriteriaTab);
            app.OutputVelocitySpectrumArrayNameEditField_2Label = uilabel(app.ISOGenericCriteriaTab);
            app.OutputVelocitySpectrumArrayNameEditField_2Label.Text = 'Output Velocity Spectrum Array Name';
            app.OutputVelocitySpectrumArrayNameEditField_generic = uieditfield(app.ISOGenericCriteriaTab, 'text');
            app.OutputVelocitySpectrumArrayNameEditField_generic.Value = 'velocity_spectrum';
            app.VelocitySpectrumdBTab = uitab(app.TabGroup);
            app.VelocitySpectrumdBTab.Title = 'Velocity Spectrum dB';
            app.CalculateButton_velox = uibutton(app.VelocitySpectrumdBTab, 'push');
            app.CalculateButton_velox.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_veloxPushed, true);
            app.CalculateButton_velox.Text = 'Calculate';
            app.PlotFrequenciesHzLabel = uilabel(app.VelocitySpectrumdBTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.InputAmplitudeListBoxLabel = uilabel(app.VelocitySpectrumdBTab);
            app.InputAmplitudeListBoxLabel.Text = 'Input Amplitude';
            app.InputAmplitudeListBox = uilistbox(app.VelocitySpectrumdBTab);
            app.InputAmplitudeListBox.Items = {'Acceleration (G)', 'Velocity (in/sec)'};
            app.InputAmplitudeListBox.Value = 'Acceleration (G)';
            app.MinEditFieldLabel = uilabel(app.VelocitySpectrumdBTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.VelocitySpectrumdBTab, 'numeric');
            app.MinEditField.Value = 4;
            app.MaxEditFieldLabel = uilabel(app.VelocitySpectrumdBTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.VelocitySpectrumdBTab, 'numeric');
            app.MaxEditField.Value = 1000;
            app.OutputVelocitySpectrumArrayNameEditFieldLabel = uilabel(app.VelocitySpectrumdBTab);
            app.OutputVelocitySpectrumArrayNameEditFieldLabel.Text = 'Output Velocity Spectrum Array Name';
            app.OutputVelocitySpectrumArrayNameEditField = uieditfield(app.VelocitySpectrumdBTab, 'text');
            app.OutputVelocitySpectrumArrayNameEditField.Value = 'velocity_dB';
            app.listbox_method = uilistbox(app.UIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIFigure);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ProcessingListBoxLabel = uilabel(app.UIFigure);
            app.ProcessingListBoxLabel.Text = 'Processing';
            app.ProcessingListBox = uilistbox(app.UIFigure);
            app.ProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.ProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessingListBoxValueChanged, true);
            app.ProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iso_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end