classdef mdof_modal_transient_ri < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mdofmodaltransientriUIFigure    matlab.ui.Figure
        mdof_modal_transient_river10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ModalAnalysisTab                matlab.ui.container.Tab
        NaturalFrequenciesTextArea      matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        StiffnessEditField              matlab.ui.control.EditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        MassEditField                   matlab.ui.control.EditField
        MassEditFieldLabel              matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsmassstiffnessLabel         matlab.ui.control.Label
        DArrayNamesLabel                matlab.ui.control.Label
        DampingTab                      matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITableDamping                  matlab.ui.control.Table
        ForceTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ArbitraryTimeHistoryTab_2       matlab.ui.container.Tab
        InputOptionsListBox             matlab.ui.control.ListBox
        InputOptionsListBoxLabel        matlab.ui.control.Label
        ForcedofnumberEditField         matlab.ui.control.EditField
        ForcedofnumberEditFieldLabel    matlab.ui.control.Label
        ForceArrayNameEditField         matlab.ui.control.EditField
        ForceArrayNameEditFieldLabel    matlab.ui.control.Label
        ReadForceInputButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SineTimeHistoryTab              matlab.ui.container.Tab
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        SettoFirstfnButton              matlab.ui.control.Button
        FreqHzEditField                 matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        ApplyForceInputButton           matlab.ui.control.Button
        ForcedofnumberEditField_2       matlab.ui.control.EditField
        ForcedofnumberEditField_2Label  matlab.ui.control.Label
        CalculateResponseTab            matlab.ui.container.Tab
        SelectTypetranslationalorrotationalLabel  matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        NumberofModestoIncludeEditField  matlab.ui.control.EditField
        NumberofModestoIncludeEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PlotsTab                        matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        SelectdofLabel                  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        AccelerationCheckBox            matlab.ui.control.CheckBox
        VelocityCheckBox                matlab.ui.control.CheckBox
        DisplacementCheckBox            matlab.ui.control.CheckBox
        SaveTab                         matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        SelectdofLabel_2                matlab.ui.control.Label
        PrefixEditField                 matlab.ui.control.EditField
        PrefixEditFieldLabel            matlab.ui.control.Label
        AccelerationCheckBox_2          matlab.ui.control.CheckBox
        VelocityCheckBox_2              matlab.ui.control.CheckBox
        DisplacementCheckBox_2          matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
    end


    properties (Access = private)
       M        % 
       K        %
       F        %
       ndof     %
       fn       %
       omegan   %
       ModeShapes  %
       MST      %  
       iu       %
       dm       %
       accel    % 
       vel      %
       dispx    %
       IFO      %
       dtype    %
       iflag;
    end

    methods (Access = private)

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITableDamping.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITableDamping.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITableDamping.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITableDamping.RowName = cell(app.ndof, 1);
                for i = 1:app.ndof
                    app.UITableDamping.RowName{i} = ['Mode ', num2str(i)];
                end

                myData = cell(app.ndof, 1);

                if(contains(nmn,'Amplification')) 
                    app.UITableDamping.ColumnName={'Q'};
                    myData(:) = {10};
                else  
                    app.UITableDamping.ColumnName={'Damping Ratio'};
                    myData(:) = {0.05};       
                end   
            end

            try
                app.UITableDamping.Data=myData;
            catch
                warndlg('fail')
            end
            app.UITableDamping.ColumnEditable = true;
        end    

        function ForceOptions(app)

            ClosePlots(app);

            Items = app.InputOptionsListBox.Items;
            im = strtrim(app.InputOptionsListBox.Value);
            app.IFO = find(strcmp(Items, im));           

            if(app.IFO==1)
                app.ForcedofnumberEditField.Visible='on';
                app.ForcedofnumberEditFieldLabel.Visible='on';            
            else
                app.ForcedofnumberEditField.Visible='off';
                app.ForcedofnumberEditFieldLabel.Visible='off';              
            end    

            Items = app.UnitsListBox.Items;
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                if(app.IFO==1)
                    app.Label.Text=sprintf('The input array must have two columns: \n  Time(sec) & Force(lbf)');
                else
                    app.Label.Text=sprintf('The input array must have a time column and a force column for each dof: \n  Time(sec) & Force(lbf)');
                end
            else
                if(app.IFO==1)
                    app.Label.Text=sprintf('The input array must have two columns: \n  Time(sec) & Force(N)');
                else
                    app.Label.Text=sprintf('The input array must have a time column and a force column for each dof: \n  Time(sec) & Force(N)');
                end
            end

        end    

        function Clearfn(app)
            app.NaturalFrequenciesTextAreaLabel.Visible='off';
            app.NaturalFrequenciesTextArea.Visible='off';
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);  
            ForceOptions(app);
            Clearfn(app);
            app.iflag=0;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)

            try
                FS=app.MassEditField.Value;
                app.M=evalin('base',FS);
            catch
                warndlg('Mass array does not exist.');
                return;
            end     

            try
                FS=app.StiffnessEditField.Value;
                app.K=evalin('base',FS);
            catch
                warndlg('Stiffness array does not exist.');
                return;
            end       

            sz=size(app.M);
            app.ndof=sz(1);

            try
                [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.K,app.M,2);
            catch
                warndlg('Calculation failed.  Check input matrices.');
                return;
            end

            ss_f = sprintf('mode    fn(Hz)');
            for i=1:app.ndof
                ss_f = sprintf('%s\n   %d.   %8.4g',ss_f, i, app.fn(i));
            end    
            

            app.NaturalFrequenciesTextAreaLabel.Visible='on';
            app.NaturalFrequenciesTextArea.Visible='on';
            app.NaturalFrequenciesTextArea.Value=ss_f; 

            ChangeDamping(app);

            app.UITable.ColumnFormat={'char','logical'};
            app.UITable.ColumnWidth={46,66};
            app.UITable_2.ColumnFormat={'char','logical'};
            app.UITable_2.ColumnWidth={46,66};

            i = 1:app.ndof;
            data_s = [cellstr(num2str(i')), repmat({'false'}, app.ndof, 1)];
            data_ss= [cellstr(num2str(i')), repmat({'translational'}, app.ndof, 1)];
 
            app.UITable.Data=data_s; 
            app.UITable_2.Data=data_s; 

            app.UITable_3.ColumnFormat = {'char',{'translational', 'rotational'}};
            app.UITable_3.ColumnWidth={46,104};     
            app.UITable_3.Data=data_ss; 

            app.iflag=1;
        end

        % Value changing function: MassEditField
        function MassEditFieldValueChanging(app, event)
            Clearfn(app);
            ClosePlots(app);
            app.iflag=0;
        end

        % Value changing function: StiffnessEditField
        function StiffnessEditFieldValueChanging(app, event)
            Clearfn(app);
            ClosePlots(app);
            app.iflag=0;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            
            Clearfn(app);
            ClosePlots(app);

            ForceOptions(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            app.dm=zeros(app.ndof,1);

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITableDamping.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITableDamping.Data;
                    v=vd{1};
                end   
                app.dm=ones(app.ndof,1)*v;
                
            else

                if(contains(nmn,'Amplification')) 
                    Q=app.UITableDamping.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITableDamping.Data;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping read');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            if(isempty(app.omegan))
                warndlg('Perform Modal Analysis first');
                return;
            end       
            
            num=str2num(app.NumberofModestoIncludeEditField.Value);
            if(num>app.ndof)
                num=app.ndof;
            end    

            if(isempty(app.dm))
                warndlg('Enter Damping first');
                return;
            end  
            if(isempty(app.F))
                warndlg('Enter Force array first');
                return;
            end

            tdif=diff(app.F(:,1));
            min_dt=min(tdif);
            max_dt=max(tdif);   
            ratio=abs((max_dt-min_dt)/max_dt);
            dt=mean(tdif);
      
            if(ratio>0.05)
                warndlg('Time step must be constant')
                return;
            end    

            FA=app.F(:,2:end);

            try
                [app.dispx,app.vel,app.accel,~,~,~]=...
                 ramp_invariant_force(app.ModeShapes,FA,num,app.ndof,app.omegan,app.dm,dt);
            catch
                warndlg('Calculation failed. Check input data.')
                return;
            end

            A = app.UITable_3.Data;
            app.dtype = A(app.ndof+1:end);

            for i=1:app.ndof

                if(strfind(app.dtype{i},'translational'))
                    if(app.iu==1)
                        app.accel(:,i)=app.accel(:,i)/386;
                    else
                        app.accel(:,i)=app.accel(:,i)/9.81;           
                    end    
                end
            end

            msgbox('Calculation complete.  Go to Plot tab');

        end

        % Value changed function: InputOptionsListBox
        function InputOptionsListBoxValueChanged(app, event)
            ForceOptions(app);
        end

        % Button pushed function: ReadForceInputButton
        function ReadForceInputButtonPushed(app, event)
            ClosePlots(app);

            try
                FS=app.ForceArrayNameEditField.Value;
                FF=evalin('base',FS);
            catch
                warndlg('Force array does not exist.');
                return;
            end       

            if(app.IFO==1)
                nt=length(FF(:,1));
                app.F=[FF(:,1) zeros(nt,app.ndof)];
                fdof=str2num(app.ForcedofnumberEditField.Value);
                app.F(:,fdof+1)=FF(:,2);
            else
                app.F=FF;
            end

            msgbox('Force array read.  Go to Calculate Response.');

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            ClosePlots(app);

            if(app.iu==1)
                YA='Accel (G)';
                YV='Vel (in/sec)';
                YD='Disp (in)';
            else
                YA='Accel (G)';
                YV='Vel (m/sec)';
                YD='Disp (m)';
            end    
            
            A = app.UITable.Data;

            pp = A(app.ndof+1:end);

            fig_num=1;

            for i=1:app.ndof
                if(pp{i}=='1')

                    if(app.AccelerationCheckBox.Value==1)
                        figure(fig_num);
                        plot(app.F(:,1),app.accel(:,i));
                        title(sprintf('Acceleration  dof %d',i));
                        if(strfind(app.dtype{i},'translational'))
                            ylabel(YA);
                        else
                            ylabel('Angular Accel (rad/sec^2)');
                        end
                        xlabel('Time (sec)');
                        grid on;
                        fig_num=fig_num+1;
                    end   
                    if(app.VelocityCheckBox.Value==1)
                        figure(fig_num);
                        plot(app.F(:,1),app.vel(:,i));
                        title(sprintf('Velocity  dof %d',i));
                        if(strfind(app.dtype{i},'translational'))
                            ylabel(YV);
                        else
                            ylabel('Angular Vel (rad/sec)');
                        end
                        xlabel('Time (sec)');
                        grid on;
                        fig_num=fig_num+1;
                    end                        
                    if(app.DisplacementCheckBox.Value==1)
                        figure(fig_num);
                        plot(app.F(:,1),app.dispx(:,i));
                        title(sprintf('Displacement  dof %d',i));
                        if(strfind(app.dtype{i},'translational'))
                            ylabel(YD);
                        else
                            ylabel('Angular Disp (rad)');
                        end
                        xlabel('Time (sec)');
                        grid on;
                        fig_num=fig_num+1;
                    end    

                end    
            end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            nn=app.PrefixEditField.Value;
            
            A = app.UITable_2.Data;

            pp = A(app.ndof+1:end);

            iflag=0;

            for i=1:app.ndof
                if(pp{i}=='1')

                    if(app.AccelerationCheckBox_2.Value==1)
                        iflag=1;
                        data=[app.F(:,1) app.accel(:,i)];
                        output_name=sprintf('%s_accel_%d',nn,i);
                        assignin('base', output_name, data ); 
                    end   
                    if(app.VelocityCheckBox_2.Value==1)
                        iflag=1;
                        data=[app.F(:,1) app.vel(:,i)];
                        output_name=sprintf('%s_vel_%d',nn,i);
                        assignin('base', output_name, data ); 
                    end                        
                    if(app.DisplacementCheckBox_2.Value==1)
                        iflag=1;
                        data=[app.F(:,1) app.dispx(:,i)];
                        output_name=sprintf('%s_disp_%d',nn,i);
                        assignin('base', output_name, data ); 
                    end    

                end    
            end

            if(iflag==1)
                msgbox('Save complete')
            else
                warndlg('No array saved')
            end    

        end

        % Button pushed function: ApplyForceInputButton
        function ApplyForceInputButtonPushed(app, event)
           
            sr=str2num(app.SampleRateHzEditField.Value);
            tmax=str2num(app.DurationsecEditField.Value);
            amp=str2num(app.AmplitudeEditField.Value);
            freq=str2num(app.FreqHzEditField.Value);

            dt=1/sr;
            omega=2*pi*freq;
            phase=0;
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*sin(omega*TT+phase);
            a=fix_size(a);
            TT=fix_size(TT);
           
            app.F=[TT(1:np) zeros(np,app.ndof)];
            fdof=str2num(app.ForcedofnumberEditField_2.Value);
            app.F(:,fdof+1)=a(1:np);

            msgbox('Force array read.  Go to Calculate Response.');
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed(app, event)
            app.FreqHzEditField.Value=sprintf('%8.5g',app.fn(1));
        end

        % Button down function: DampingTab
        function DampingTabButtonDown(app, event)
            if(app.iflag==0)
                warndlg('Perform Modal Analysis First');
                return;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mdofmodaltransientriUIFigure = uifigure('Visible', 'off');
            app.mdofmodaltransientriUIFigure.Name = 'mdof modal transient ri';
            app.ReturnButton = uibutton(app.mdofmodaltransientriUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.mdofmodaltransientriUIFigure);
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.DArrayNamesLabel = uilabel(app.ModalAnalysisTab);
            app.DArrayNamesLabel.Text = '** 2D Array Names ** ';
            app.UnitsmassstiffnessLabel = uilabel(app.ModalAnalysisTab);
            app.UnitsmassstiffnessLabel.Text = 'Units:  mass, stiffness';
            app.UnitsListBox = uilistbox(app.ModalAnalysisTab);
            app.UnitsListBox.Items = {'lbf sec^2/in, lbf/in', 'kg, N/m'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'lbf sec^2/in, lbf/in';
            app.MassEditFieldLabel = uilabel(app.ModalAnalysisTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ModalAnalysisTab, 'text');
            app.MassEditField.ValueChangingFcn = createCallbackFcn(app, @MassEditFieldValueChanging, true);
            app.StiffnessEditFieldLabel = uilabel(app.ModalAnalysisTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.ModalAnalysisTab, 'text');
            app.StiffnessEditField.ValueChangingFcn = createCallbackFcn(app, @StiffnessEditFieldValueChanging, true);
            app.CalculateButton_2 = uibutton(app.ModalAnalysisTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.ModalAnalysisTab);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.ModalAnalysisTab);
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTab.ButtonDownFcn = createCallbackFcn(app, @DampingTabButtonDown, true);
            app.UITableDamping = uitable(app.DampingTab);
            app.UITableDamping.ColumnName = {'Column 1'};
            app.UITableDamping.RowName = {};
            app.UITableDamping.ColumnEditable = true;
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.TabGroup2 = uitabgroup(app.ForceTab);
            app.ArbitraryTimeHistoryTab_2 = uitab(app.TabGroup2);
            app.ArbitraryTimeHistoryTab_2.Title = 'Arbitrary Time History ';
            app.Label = uilabel(app.ArbitraryTimeHistoryTab_2);
            app.ReadForceInputButton = uibutton(app.ArbitraryTimeHistoryTab_2, 'push');
            app.ReadForceInputButton.ButtonPushedFcn = createCallbackFcn(app, @ReadForceInputButtonPushed, true);
            app.ReadForceInputButton.Text = 'Read Force Input';
            app.ForceArrayNameEditFieldLabel = uilabel(app.ArbitraryTimeHistoryTab_2);
            app.ForceArrayNameEditFieldLabel.Text = 'Force  Array Name';
            app.ForceArrayNameEditField = uieditfield(app.ArbitraryTimeHistoryTab_2, 'text');
            app.ForcedofnumberEditFieldLabel = uilabel(app.ArbitraryTimeHistoryTab_2);
            app.ForcedofnumberEditFieldLabel.Text = 'Force dof number';
            app.ForcedofnumberEditField = uieditfield(app.ArbitraryTimeHistoryTab_2, 'text');
            app.InputOptionsListBoxLabel = uilabel(app.ArbitraryTimeHistoryTab_2);
            app.InputOptionsListBoxLabel.Text = 'Input Options';
            app.InputOptionsListBox = uilistbox(app.ArbitraryTimeHistoryTab_2);
            app.InputOptionsListBox.Items = {'Force at Single dof', 'Force Array for All dof'};
            app.InputOptionsListBox.ValueChangedFcn = createCallbackFcn(app, @InputOptionsListBoxValueChanged, true);
            app.InputOptionsListBox.Value = 'Force at Single dof';
            app.SineTimeHistoryTab = uitab(app.TabGroup2);
            app.SineTimeHistoryTab.Title = 'Sine Time History';
            app.ForcedofnumberEditField_2Label = uilabel(app.SineTimeHistoryTab);
            app.ForcedofnumberEditField_2Label.Text = 'Force dof number';
            app.ForcedofnumberEditField_2 = uieditfield(app.SineTimeHistoryTab, 'text');
            app.ApplyForceInputButton = uibutton(app.SineTimeHistoryTab, 'push');
            app.ApplyForceInputButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyForceInputButtonPushed, true);
            app.ApplyForceInputButton.Text = 'Apply Force Input';
            app.DurationsecEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.AmplitudeEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.FreqHzEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.SettoFirstfnButton = uibutton(app.SineTimeHistoryTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.SampleRateHzEditFieldLabel = uilabel(app.SineTimeHistoryTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.SineTimeHistoryTab, 'text');
            app.CalculateResponseTab = uitab(app.TabGroup);
            app.CalculateResponseTab.Title = 'Calculate Response';
            app.CalculateButton = uibutton(app.CalculateResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NumberofModestoIncludeEditFieldLabel = uilabel(app.CalculateResponseTab);
            app.NumberofModestoIncludeEditFieldLabel.Text = 'Number of Modes to Include';
            app.NumberofModestoIncludeEditField = uieditfield(app.CalculateResponseTab, 'text');
            app.UITable_3 = uitable(app.CalculateResponseTab);
            app.UITable_3.ColumnName = {'dof'; 'Type'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = [false true];
            app.SelectTypetranslationalorrotationalLabel = uilabel(app.CalculateResponseTab);
            app.SelectTypetranslationalorrotationalLabel.Text = {'Select Type:  '; 'translational or rotational'};
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.DisplacementCheckBox = uicheckbox(app.PlotsTab);
            app.DisplacementCheckBox.Text = 'Displacement';
            app.DisplacementCheckBox.Value = true;
            app.VelocityCheckBox = uicheckbox(app.PlotsTab);
            app.VelocityCheckBox.Text = 'Velocity';
            app.AccelerationCheckBox = uicheckbox(app.PlotsTab);
            app.AccelerationCheckBox.Text = 'Acceleration';
            app.PlotButton = uibutton(app.PlotsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SelectdofLabel = uilabel(app.PlotsTab);
            app.SelectdofLabel.Text = 'Select dof';
            app.UITable = uitable(app.PlotsTab);
            app.UITable.ColumnName = {'dof'; 'Plot'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplacementCheckBox_2 = uicheckbox(app.SaveTab);
            app.DisplacementCheckBox_2.Text = 'Displacement';
            app.DisplacementCheckBox_2.Value = true;
            app.VelocityCheckBox_2 = uicheckbox(app.SaveTab);
            app.VelocityCheckBox_2.Text = 'Velocity';
            app.AccelerationCheckBox_2 = uicheckbox(app.SaveTab);
            app.AccelerationCheckBox_2.Text = 'Acceleration';
            app.PrefixEditFieldLabel = uilabel(app.SaveTab);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.SaveTab, 'text');
            app.PrefixEditField.Value = 'response';
            app.SelectdofLabel_2 = uilabel(app.SaveTab);
            app.SelectdofLabel_2.Text = 'Select dof';
            app.UITable_2 = uitable(app.SaveTab);
            app.UITable_2.ColumnName = {'dof'; 'Save'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true];
            app.mdof_modal_transient_river10byTomIrvineLabel = uilabel(app.mdofmodaltransientriUIFigure);
            app.mdof_modal_transient_river10byTomIrvineLabel.Text = 'mdof_modal_transient_ri    ver 1.1   by Tom Irvine     MDOF system reponse to applied force via digital recursive filtering relationship.';
            % Show the figure after all components are created
            app.mdofmodaltransientriUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mdof_modal_transient_ri

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mdofmodaltransientriUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.mdofmodaltransientriUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mdofmodaltransientriUIFigure)
        end
    end
end