classdef mdof_srs_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mdofsrsUIFigure                matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SRSSpecificationTab            matlab.ui.container.Tab
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel  matlab.ui.control.Label
        ModalParametersTab             matlab.ui.container.Tab
        MassNormalizedEigenvectorsListBox  matlab.ui.control.ListBox
        MassNormalizedEigenvectorsListBoxLabel  matlab.ui.control.Label
        ParticipationFactorsListBox    matlab.ui.control.ListBox
        ParticipationFactorsListBoxLabel  matlab.ui.control.Label
        NaturalFrequenciesHzListBox    matlab.ui.control.ListBox
        NaturalFrequenciesHzListBoxLabel  matlab.ui.control.Label
        DArrayLabel_3                  matlab.ui.control.Label
        DArrayLabel_2                  matlab.ui.control.Label
        DArrayLabel                    matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        CalculateTab                   matlab.ui.container.Tab
        SRSSpecificationTextArea       matlab.ui.control.TextArea
        SRSSpecificationTextAreaLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        mdof_srs_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
    end
    
    methods (Access = private)

        function update_arrays(app)

            cell_array=update_1D_array();
            app.NaturalFrequenciesHzListBox.Items=unique(sort(cell_array)); 
            app.NaturalFrequenciesHzListBox.Value=app.NaturalFrequenciesHzListBox.Items{1};  

            cell_array=update_1D_array();
            app.ParticipationFactorsListBox.Items=unique(sort(cell_array)); 
            app.ParticipationFactorsListBox.Value=app.ParticipationFactorsListBox.Items{1}; 

            cell_array=update_nxn_array();
            app.MassNormalizedEigenvectorsListBox.Items=unique(sort(cell_array)); 
            app.MassNormalizedEigenvectorsListBox.Value=app.MassNormalizedEigenvectorsListBox.Items{1}; 
        end    

        function update_srs(app)
            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_srs(app);
            update_arrays(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            iu = find(strcmp(Items, im));
            
            Items = strtrim(app.listbox_method.Items);
            im = strtrim(app.listbox_method.Value);
            k = find(strcmp(Items, im));

            if(k==1)
              FS=strtrim(app.SelectInputArrayListBox.Value);
              app.THM=evalin('base',FS);   
            end
            
            num_srs=length(app.THM(:,1));
            
            try
                FS1=strtrim(app.NaturalFrequenciesHzListBox.Value);
                fn=evalin('base',FS1);
                fn=fix_size(fn);
                szfn=size(fn);
                if(szfn(2)>=2)
                    warndlg('Natural Frequency Array must have only 1 column');
                    return;
                end
            catch
                warndlg(' Natural Frequency array unavailable');
                return
            end    
            
            try
                FS2=strtrim(app.ParticipationFactorsListBox.Value);
                pf=evalin('base',FS2);
                pf=pf';
                pf=fix_size(pf);
                sz=size(pf);
                if(sz(2)>=2)
                    warndlg('Participation Factor Array must have only 1 column');
                    return;
                end    
            catch
                warndlg(' Participation Factor array unavailable');    
                return    
            end 
            
            try
                FS3=strtrim(app.MassNormalizedEigenvectorsListBox.Value);
                ModeShapes=evalin('base',FS3);
                szM=size(ModeShapes);
                if(szM(1)~=szfn(1) || szM(2)~=szfn(1))
                    out1=sprintf('Mode Shape Array must have dimensions (%d x %d)',szfn(1),szfn(1));
                    szM
                    disp(out1);
                    warndlg(out1);
                    return;
                end      
            catch
                warndlg(' Mode Shape array unavailable');      
                return    
            end 
            
            num_fn=length(fn);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            omegan=2*pi*fn;
            
            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
              
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  
            
            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);
            
             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
              
            
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end
            
            for i=1:num_fn
                
                for k=1:(num_srs-1)
                    if(fn(i)>=f(k) && fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/omegan(i); 
                        rd_srs(i)=a_srs(i)/omegan(i)^2;    
                        break;
                    end    
                end
            end 

            
            for i=1:num_fn
                
                for j=1:num_fn
                    
                     PFM=pf(j)*ModeShapes(i,j);
                     
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
              
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
                   
            end
            
            if(iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            
            sp=sprintf('SRS Specification \n  Mode   fn(Hz)   Accel(G)  ');
            
            for i=1:num_fn
                sp=sprintf('%s\n   %d    %8.4g   %8.4g ',sp,i,fn(i),a_srs(i));           
            end
            

            ss=sprintf(' Response Acceleration (G) \n dof    SRSS    Abssum ');
            
            for i=1:num_fn
                ss=sprintf('%s\n %d  %8.4g  %8.4g ',ss,i,accel_srss(i),accel_abssum(i));
            end
            

            if(iu==1)
                sv=sprintf('\n Pseudo Velocity (in/sec)');
            else
                sv=sprintf('\n Pseudo Velocity (cm/sec)');    
            end
            
            ss=sprintf('%s\n %s\n dof    SRSS    Abssum  ',ss,sv);
            
            for i=1:num_fn
                ss=sprintf('%s\n %d  %8.4g  %8.4g ',ss,i,pv_srss(i),pv_abssum(i));
            end
            
            
            if(iu==1)
                sd=sprintf('\n Relative Displacement (in)');
            else
                sd=sprintf('\n Relative Displacement (mm)');    
            end    
                
            ss=sprintf('%s\n %s\n dof    SRSS    Abssum  ',ss,sd);
            
            for i=1:num_fn
                ss=sprintf('%s\n %d   %8.4g  %8.4g ',ss,i,rd_srss(i),rd_abssum(i));
            end
            
            app.SRSSpecificationTextArea.Value=sp;
            app.ResultsTextArea.Value=ss;

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update_arrays(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mdofsrsUIFigure = uifigure('Visible', 'off');
            app.mdofsrsUIFigure.Name = 'mdof srs';
            app.mdof_srs_aver10byTomIrvineLabel = uilabel(app.mdofsrsUIFigure);
            app.mdof_srs_aver10byTomIrvineLabel.Text = 'mdof_srs_a   ver 1.0   by Tom Irvine       This script estimates the MDOF response to an SRS.';
            app.ReturnButton = uibutton(app.mdofsrsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.mdofsrsUIFigure);
            app.SRSSpecificationTab = uitab(app.TabGroup);
            app.SRSSpecificationTab.Title = 'SRS Specification';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel = uilabel(app.SRSSpecificationTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel.Text = 'The input SRS must have two columns: Natural Frequency (Hz) & SRS';
            app.listbox_method = uilistbox(app.SRSSpecificationTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.SRSSpecificationTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.SRSSpecificationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.SRSSpecificationTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.SRSSpecificationTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.UnitsListBoxLabel = uilabel(app.SRSSpecificationTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SRSSpecificationTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm ', 'm/sec^2, m/sec, mm '};
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.SRSSpecificationTab);
            app.AmplificationFactorQEditFieldLabel.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField = uieditfield(app.SRSSpecificationTab, 'text');
            app.AmplificationFactorQEditField.Value = '10';
            app.ModalParametersTab = uitab(app.TabGroup);
            app.ModalParametersTab.Title = 'Modal Parameters';
            app.UpdateButton = uibutton(app.ModalParametersTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DArrayLabel = uilabel(app.ModalParametersTab);
            app.DArrayLabel.Text = '1D Array';
            app.DArrayLabel_2 = uilabel(app.ModalParametersTab);
            app.DArrayLabel_2.Text = '1D Array';
            app.DArrayLabel_3 = uilabel(app.ModalParametersTab);
            app.DArrayLabel_3.Text = '2D Array';
            app.NaturalFrequenciesHzListBoxLabel = uilabel(app.ModalParametersTab);
            app.NaturalFrequenciesHzListBoxLabel.Text = 'Natural Frequencies (Hz)';
            app.NaturalFrequenciesHzListBox = uilistbox(app.ModalParametersTab);
            app.ParticipationFactorsListBoxLabel = uilabel(app.ModalParametersTab);
            app.ParticipationFactorsListBoxLabel.Text = 'Participation Factors';
            app.ParticipationFactorsListBox = uilistbox(app.ModalParametersTab);
            app.MassNormalizedEigenvectorsListBoxLabel = uilabel(app.ModalParametersTab);
            app.MassNormalizedEigenvectorsListBoxLabel.Text = 'Mass Normalized Eigenvectors';
            app.MassNormalizedEigenvectorsListBox = uilistbox(app.ModalParametersTab);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.ResultsTextAreaLabel = uilabel(app.CalculateTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.CalculateTab);
            app.ResultsTextArea.Editable = 'off';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SRSSpecificationTextAreaLabel = uilabel(app.CalculateTab);
            app.SRSSpecificationTextAreaLabel.Text = 'SRS Specification';
            app.SRSSpecificationTextArea = uitextarea(app.CalculateTab);
            app.SRSSpecificationTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.mdofsrsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mdof_srs_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mdofsrsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.mdofsrsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mdofsrsUIFigure)
        end
    end
end