classdef modal_density_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        modaldensityUIFigure            matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        AirborneAcousticalTab           matlab.ui.container.Tab
        SpeedofSoundinsecEditField      matlab.ui.control.EditField
        SpeedofSoundinsecEditFieldLabel  matlab.ui.control.Label
        TemperaturedegCEditField        matlab.ui.control.EditField
        TemperaturedegCEditFieldLabel   matlab.ui.control.Label
        MediumListBox                   matlab.ui.control.ListBox
        MediumListBoxLabel              matlab.ui.control.Label
        TabGroup5                       matlab.ui.container.TabGroup
        DPipeTab                        matlab.ui.container.Tab
        ResultsReadOnlyTextArea_1D_Pipe  matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel    matlab.ui.control.Label
        LengthinEditField_1D_Pipe       matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        ViewEquationButton              matlab.ui.control.Button
        CalculateButton_1D_Pipe         matlab.ui.control.Button
        DRectangleTab                   matlab.ui.container.Tab
        OutputArrayNameEditField_2D_Rect  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        WidthinEditField_2D_Rect        matlab.ui.control.EditField
        WidthinEditFieldLabel           matlab.ui.control.Label
        LengthinEditField_2D_Rect       matlab.ui.control.EditField
        LengthinEditField_2Label        matlab.ui.control.Label
        ViewEquationButton_2            matlab.ui.control.Button
        CalculateButton_2D_Rect         matlab.ui.control.Button
        DRectangularVolumeTab           matlab.ui.container.Tab
        OutputArrayNameEditField_3D_Vol  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_2  matlab.ui.control.Label
        HeightinEditField_3D_Vol        matlab.ui.control.EditField
        HeightinEditFieldLabel          matlab.ui.control.Label
        WidthinEditField_3D_Vol         matlab.ui.control.EditField
        WidthinEditField_2Label         matlab.ui.control.Label
        LengthinEditField_3D_Vol        matlab.ui.control.EditField
        LengthinEditField_3Label        matlab.ui.control.Label
        ViewEquationButton_3            matlab.ui.control.Button
        CalculateButton_3D_Vol          matlab.ui.control.Button
        DCylinderTab                    matlab.ui.container.Tab
        OutputArrayNameEditField_3D_Cyl  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_3  matlab.ui.control.Label
        RadiusinEditField_3D_Cyl        matlab.ui.control.EditField
        RadiusinEditFieldLabel          matlab.ui.control.Label
        HeightinEditField_3D_Cyl        matlab.ui.control.EditField
        HeightinEditField_2Label        matlab.ui.control.Label
        ViewEquationButton_4            matlab.ui.control.Button
        CalculateButton_3D_Cyl          matlab.ui.control.Button
        DSphereTab                      matlab.ui.container.Tab
        OutputArrayNameEditField_3D_Sphere  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_4  matlab.ui.control.Label
        RadiusinEditField_3D_Sphere     matlab.ui.control.EditField
        RadiusinEditField_2Label        matlab.ui.control.Label
        ViewEquationButton_5            matlab.ui.control.Button
        CalculateButton_3D_Sphere       matlab.ui.control.Button
        DOtherTab                       matlab.ui.container.Tab
        OutputArrayNameEditField_3D_Other  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_5  matlab.ui.control.Label
        Volumein3EditField              matlab.ui.control.EditField
        Volumein3EditFieldLabel         matlab.ui.control.Label
        ViewEquationButton_6            matlab.ui.control.Button
        CalculateButton_3D_Other        matlab.ui.control.Button
        UnitsListBox_aco                matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        StructuralTab                   matlab.ui.container.Tab
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RodLongTab                      matlab.ui.container.Tab
        ResultsReadOnlyTextArea_1D_Rod  matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_2  matlab.ui.control.Label
        ViewEquationButton_7            matlab.ui.control.Button
        LengthinEditField_rod           matlab.ui.control.EditField
        LengthinEditFieldLabel_rod      matlab.ui.control.Label
        CalculateButton_rod             matlab.ui.control.Button
        BeamTab                         matlab.ui.container.Tab
        ResultsReadOnlyTextArea_beam    matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_6  matlab.ui.control.Label
        ViewEquationButton_8            matlab.ui.control.Button
        OutputArrayNameEditField_beam   matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_10  matlab.ui.control.Label
        CalculateButton_beam            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        ModeTypeListBox                 matlab.ui.control.ListBox
        ModeTypeListBoxLabel            matlab.ui.control.Label
        PlatesTab                       matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        RectangularTab                  matlab.ui.container.Tab
        OutputArrayNameEditField_rplate  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_9  matlab.ui.control.Label
        ThicknessinEditField_rect       matlab.ui.control.EditField
        ThicknessinEditFieldLabel_rect  matlab.ui.control.Label
        WidthinEditField_rect           matlab.ui.control.EditField
        WidthinEditFieldLabel_rect      matlab.ui.control.Label
        LengthinEditField_rect          matlab.ui.control.EditField
        LengthinEditFieldLabel_rect     matlab.ui.control.Label
        ResultsReadOnlyTextArea_rect    matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_4  matlab.ui.control.Label
        StructureListBox                matlab.ui.control.ListBox
        StructureListBoxLabel           matlab.ui.control.Label
        ViewEquationButton_9            matlab.ui.control.Button
        CalculateButton_plate_rect      matlab.ui.control.Button
        CircularAnnularTab              matlab.ui.container.Tab
        UnitsListBox_circular_geometry  matlab.ui.control.ListBox
        UnitsListBox_2Label_2           matlab.ui.control.Label
        DiameterinEditField_circular_inner  matlab.ui.control.EditField
        DiameterinEditFieldLabel_circular_inner  matlab.ui.control.Label
        ViewEquationButton_10           matlab.ui.control.Button
        ResultsReadOnlyTextArea_circular  matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_5  matlab.ui.control.Label
        OutputArrayNameEditField_circular  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_8  matlab.ui.control.Label
        ThickinEditField_circular       matlab.ui.control.EditField
        ThickinEditFieldLabel_circular  matlab.ui.control.Label
        DiameterinEditField_circular    matlab.ui.control.EditField
        DiameterinEditFieldLabel_circular  matlab.ui.control.Label
        CalculateButton_plate_circ      matlab.ui.control.Button
        CylindricalShellTab             matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SzechenyiTab                    matlab.ui.container.Tab
        OutputArrayNameEditField_cyl    matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_7  matlab.ui.control.Label
        ResultsReadOnlyTextArea_cyl     matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel_3  matlab.ui.control.Label
        ThickinEditField_cyl            matlab.ui.control.EditField
        ThickinEditFieldLabel_cyl       matlab.ui.control.Label
        DiameterinEditField_cyl         matlab.ui.control.EditField
        DiameterinEditFieldLabel_cyl    matlab.ui.control.Label
        LengthinEditField_cyl           matlab.ui.control.EditField
        LengthinEditFieldLabel_cyl      matlab.ui.control.Label
        ViewEquationButton_cyl          matlab.ui.control.Button
        CalculateButton_cyl_sze         matlab.ui.control.Button
        WaveTab                         matlab.ui.container.Tab
        GotoWaveMethodButton            matlab.ui.control.Button
        HoneycombSandwichTab            matlab.ui.container.Tab
        GotoHoneycombSandwichButton     matlab.ui.control.Button
        UnitsListBox_str                matlab.ui.control.ListBox
        UnitsListBox_2Label             matlab.ui.control.Label
        ConvertFreqArraytoModalDensityTab  matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        OutputArrayNameEditField_modal_density  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_6  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        ReturnButton                    matlab.ui.control.Button
        modal_density_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        tpi
        in_per_meters
        fmin
        fmax
        fc
        iua
        ius
        su
        lu
        igas
        iacovol
        c
        mph
        stitle
        icross
        istructure
        imode_type
        THM
        nMethod
        em
        mu
        md
        igeo_circular
    end
    
    methods (Access = private)

        function circular_geometry(app)

            Items = strtrim(app.UnitsListBox_circular_geometry.Items);
            im = strtrim(app.UnitsListBox_circular_geometry.Value);
            app.igeo_circular = find(strcmp(Items, im));


            if(app.ius==1)  % English
                app.ThickinEditFieldLabel_circular.Text='Thickness (in)'; 
            else
                app.ThickinEditFieldLabel_circular.Text='Thickness (mm)';
            end    


            if(app.igeo_circular==1)
                app.DiameterinEditField_circular_inner.Visible='off';
                app.DiameterinEditFieldLabel_circular_inner.Visible='off';

                if(app.ius==1)  % English
                    app.DiameterinEditFieldLabel_circular.Text='Diameter (in)';   
                else
                    app.DiameterinEditFieldLabel_circular.Text='Diameter (m)'; 
                end    

            else    
                app.DiameterinEditField_circular_inner.Visible='on';
                app.DiameterinEditFieldLabel_circular_inner.Visible='on';  

                if(app.ius==1)  % English
                    app.DiameterinEditFieldLabel_circular.Text='Outer Diameter (in)';   
                    app.DiameterinEditFieldLabel_circular_inner.Text='Inner Diameter (in)';
                else
                    app.DiameterinEditFieldLabel_circular.Text='Outer Diameter (m)'; 
                    app.DiameterinEditFieldLabel_circular_inner.Text='Inner Diameter (m)';                  
                end    

            end

        
        end    

        function preprocess_structure(app)
            app.em=str2num(app.ElasticModuluspsiEditField.Value);
            app.md=str2num(app.MassDensitylbmin3EditField.Value);
            app.mu=str2num(app.PoissonRatioEditField.Value);
                
            if(app.ius==1)
                app.md=app.md/386;
                app.su='in/sec';
            else    
                [app.em]=GPa_to_Pa(app.em);
                app.su='m/sec';
            end
                
            app.c=sqrt(app.em/app.md);
        end

        function plot_mph(app)
            fig_num=1;
    
            x_label='Frequency (Hz)';
            y_label='Modal Density (modes/Hz)';
            t_string=app.stitle;
            ppp=[app.fc app.mph];
            mmd=7;
            
            [fig_num]=plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,mmd);          
        end    

        function aco_speed(app)

            app.ResultsReadOnlyTextArea_1D_Pipe.Value='';
            app.ResultsReadOnlyTextArea_cyl.Value='';

            if(app.igas==1)  % air
            
                    Tc=str2num(app.TemperaturedegCEditField.Value);
                    Tk=Tc+273.15;
                    
                    ratio=1.402;
                    M=28.97;
                    
                    R=8314.3;  % J/(kgmole K)
                    app.c=sqrt(ratio*R*Tk/M);
                    
                    if(app.iua==1)
                        app.c=app.c*app.in_per_meters;
                    end
                    if(app.iua==2)
                        app.c=app.c*app.in_per_meters/12;
                    end        
                    
            else
                    app.c=str2num(app.SpeedofSoundinsecEditField.Value);         
            end    
        end

        function units_str(app)
            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
            app.ResultsReadOnlyTextArea_cyl.Value='';
            app.ResultsReadOnlyTextArea_circular.Value='';
            app.ResultsReadOnlyTextArea_rect.Value='';
            app.ResultsReadOnlyTextArea_beam.Value='';

            Items = strtrim(app.UnitsListBox_str.Items);
            im = strtrim(app.UnitsListBox_str.Value);
            app.ius = find(strcmp(Items, im));

            Items = strtrim(app.MaterialListBox.Items);
            im = strtrim(app.MaterialListBox.Value);
            imat = find(strcmp(Items, im));            

            Items = strtrim(app.CrossSectionListBox.Items);
            im = strtrim(app.CrossSectionListBox.Value);
            app.icross = find(strcmp(Items, im));     

            Items = strtrim(app.ModeTypeListBox.Items);
            im = strtrim(app.ModeTypeListBox.Value);
            app.imode_type = find(strcmp(Items, im)); 

            Items = strtrim(app.StructureListBox.Items);
            im = strtrim(app.StructureListBox.Value);
            app.istructure = find(strcmp(Items, im)); 

            %%%
           
            if(app.ius==1)  % English

                app.LengthinEditFieldLabel_rod.Text='Length (in)';
                app.ThickinEditFieldLabel_cyl.Text='Wall Thickness (in)';
                app.DiameterinEditFieldLabel_cyl.Text='Diameter (in)';
                app.LengthinEditFieldLabel_cyl.Text='Length (in)';

                app.LengthinEditFieldLabel_rect.Text='Length (in)';
                app.WidthinEditFieldLabel_rect.Text='Width (in)';
                app.ThicknessinEditFieldLabel_rect.Text='Thickness (in)';
   
            else
                app.LengthinEditFieldLabel_rod.Text='Length (m)';
                app.ThickinEditFieldLabel_cyl.Text='Wall Thickness (mm)';
                app.DiameterinEditFieldLabel_cyl.Text='Diameter (m)';
                app.LengthinEditFieldLabel_cyl.Text='Length (m)';  

                app.LengthinEditFieldLabel_rect.Text='Length (m)';
                app.WidthinEditFieldLabel_rect.Text='Width (m)';
                app.ThicknessinEditFieldLabel_rect.Text='Thickness (mm)';               
     
            end   


            if(app.ius==1) % English

                if(app.imode_type==1) % bending

                    if(app.icross==1) % rect
                        data = cell(3, 2);
                        data{2,1}='Width (in)';
                        data{3,1}='Thickness (in)';
                    end 
                    if(app.icross==2) % pipe
                        data = cell(3, 2);
                        data{2,1}='Outer Diameter (in)';
                        data{3,1}='Inner Diameter (in)';
                    end 
                    if(app.icross==3) % solid cyl
                        data = cell(2, 2);
                        data{2,1}='Diameter (in)';
                    end                 
                    if(app.icross==4) % other
                        data = cell(3, 2);
                        data{2,1}='Cross-Section Area (in^2)';
                        data{3,1}='Area MOI (in^4)';           
                    end                

                else % torsion

                    if(app.icross==1) % rect
                        data = cell(3, 2);
                        data{2,1}='Width (in)';
                        data{3,1}='Thickness (in)';
                    end 
                    if(app.icross==2) % pipe
                        data = cell(1, 2);
                    end 
                    if(app.icross==3) % solid cyl
                        data = cell(1, 2);
                    end                 
                    if(app.icross==4) % other
                        data = cell(4, 2);
                        data{2,1}='Polar MOI (in^4)';
                        data{3,1}='Ix MOI (in^4)';  
                        data{4,1}='Iy MOI (in^4)';                     
                    end                   
                end    

                data{1,1}='Length (in)';

            else  % metric

                if(app.imode_type==1) % bending

                    if(app.icross==1) % rect
                        data = cell(3, 2);
                        data{2,1}='Width (m)';
                        data{3,1}='Thickness (mm)';
                    end 
                    if(app.icross==2) % pipe
                        data = cell(3, 2);
                        data{2,1}='Outer Diameter (m)';
                        data{3,1}='Inner Diameter (m)';
                    end 
                    if(app.icross==3) % solid cyl
                        data = cell(2, 2);
                        data{2,1}='Diameter (m)';
                    end                 
                    if(app.icross==4) % other
                        data = cell(3, 2);
                        data{2,1}='Cross-Section Area (m^2)';
                        data{3,1}='Area MOI (m^4)';           
                    end                

                else % torsion

                    if(app.icross==1) % rect
                        data = cell(3, 2);
                        data{2,1}='Width (m)';
                        data{3,1}='Thickness (mm)';
                    end 
                    if(app.icross==2) % pipe
                        data = cell(1, 2);
                    end 
                    if(app.icross==3) % solid cyl
                        data = cell(1, 2);
                    end                 
                    if(app.icross==4) % other
                        data = cell(4, 2);
                        data{2,1}='Polar MOI (m^4)';
                        data{3,1}='Ix MOI (m^4)';  
                        data{4,1}='Iy MOI (m^4)';                     
                    end                   
                end    

                data{1,1}='Length (m)';

            end    

            app.UITable.Data=data;

            
                if(app.ius==1)  % English
                    if(imat==1) % aluminum
                        elastic_modulus=1e+007;
                        mass_density=0.1;  
                    end  
                    if(imat==2)  % steel
                        elastic_modulus=3e+007;
                        mass_density= 0.28;         
                    end
                    if(imat==3)  % copper
                        elastic_modulus=1.6e+007;
                        mass_density=  0.322;
                    end
                    if(imat==4)  % G10
                        elastic_modulus=2.7e+006;
                        mass_density=  0.065;
                    end
                else                 % metric
                    if(imat==1)  % aluminum
                        elastic_modulus=70;
                        mass_density=  2700;
                    end
                    if(imat==2)  % steel
                        elastic_modulus=205;
                        mass_density=  7700;        
                    end
                    if(imat==3)   % copper
                        elastic_modulus=110;
                        mass_density=  8900;
                    end
                    if(imat==4)  % G10
                        elastic_modulus=18.6;
                        mass_density=  1800;
                    end
                end
                
                if(imat==1) % aluminum
                    poisson=0.33;  
                end  
                if(imat==2)  % steel
                    poisson= 0.30;         
                end
                if(imat==3)  % copper
                    poisson=  0.33;
                end
                if(imat==4)  % G10
                    poisson=  0.12;
                end    
             
                if(imat<5)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
                    ss3=sprintf('%8.4g',poisson);  

                    app.ElasticModuluspsiEditField.Value=ss1;
                    app.MassDensitylbmin3EditField.Value=ss2;
                    app.PoissonRatioEditField.Value=ss3;
                end
            
                if(imat==5)
                    app.ElasticModuluspsiEditField.Value='';
                    app.MassDensitylbmin3EditField.Value='';
                end

        end    

        function units_aco(app)

            app.ResultsReadOnlyTextArea_1D_Pipe.Value='';

            Items = strtrim(app.UnitsListBox_aco.Items);
            im = strtrim(app.UnitsListBox_aco.Value);
            app.iua = find(strcmp(Items, im));

            Items = strtrim(app.MediumListBox.Items);
            im = strtrim(app.MediumListBox.Value);
            app.igas = find(strcmp(Items, im));

            if(app.igas==1)
                app.TemperaturedegCEditField.Visible='on';
                app.TemperaturedegCEditFieldLabel.Visible='on';
                app.SpeedofSoundinsecEditField.Visible='off';
                app.SpeedofSoundinsecEditFieldLabel.Visible='off';
            else
                app.TemperaturedegCEditField.Visible='off';
                app.TemperaturedegCEditFieldLabel.Visible='off';
                app.SpeedofSoundinsecEditField.Visible='on';
                app.SpeedofSoundinsecEditFieldLabel.Visible='on';               
            end    

            if(app.iua==1)
                app.lu='in';
                app.su='in/sec';
            end
            if(app.iua==2)
                app.lu='ft';
                app.su='ft/sec';
            end
            if(app.iua==3)
                app.lu='m';
                app.su='m/sec';
            end 

            ss=sprintf('Speed of Sound (%s)',app.su);
            app.SpeedofSoundinsecEditFieldLabel.Text=ss;

            sq=sprintf('Length (%s)',app.lu);
            app.LengthinEditFieldLabel.Text=sq;
            app.LengthinEditField_2Label.Text=sq;
            app.LengthinEditField_3Label.Text=sq;

            sw=sprintf('Width (%s)',app.lu);
            app.WidthinEditFieldLabel.Text=sw;
            app.WidthinEditField_2Label.Text=sw;

            sh=sprintf('Height (%s)',app.lu);
            app.HeightinEditFieldLabel.Text=sh;
            app.HeightinEditField_2Label.Text=sh;

            sv=sprintf('Volume (%s^3)',app.lu);
            app.Volumein3EditFieldLabel.Text=sv;

            sr=sprintf('Raidus (%s)',app.lu);
            app.RadiusinEditFieldLabel.Text=sr;
            app.RadiusinEditField_2Label.Text=sr;

        end    

        function CloseFigures(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            
        end

        function update(app)

            [cell_array]=update_fn();

            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method.Value=app.listbox_method.Items{1};
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CloseFigures(app);
            circular_geometry(app);
            app.tpi=2*pi;
            app.in_per_meters=39.37;
            [~,app.fc,~,~,app.fmin,app.fmax]=SEA_one_third_octave_frequencies_max_min();
            units_aco(app);
            units_str(app);
            app.fc=fix_size(app.fc);
            app.mph=zeros(length(app.fc),1);
            update(app);
            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
            app.ResultsReadOnlyTextArea_cyl.Value='';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: GotoHoneycombSandwichButton
        function GotoHoneycombSandwichButtonPushed(app, event)
            handles.s=honeycomb_sandwich_modal_density;

            try
                set(handles.s,'Visible','on');  
            catch
            end
        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            
            A = imread('1D_Pipe_md.jpg');
            figure(994) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Button pushed function: ViewEquationButton_2
        function ViewEquationButton_2Pushed(app, event)
         

            A = imread('2D_Rectangle_md.jpg');
            figure(995) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Button pushed function: ViewEquationButton_3
        function ViewEquationButton_3Pushed(app, event)
         
            A = imread('3D_Rectangular Prism_md.jpg');
            figure(996) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Button pushed function: ViewEquationButton_4
        function ViewEquationButton_4Pushed(app, event)
        
            A = imread('3D_Cylinder_md.jpg');
            figure(997) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Button pushed function: ViewEquationButton_5
        function ViewEquationButton_5Pushed(app, event)
         
            A = imread('3D_Sphere_md.jpg');
            figure(998) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Button pushed function: ViewEquationButton_6
        function ViewEquationButton_6Pushed(app, event)
       
            A = imread('3D_Other_md.jpg');
            figure(999) 
            imshow(A,'border','tight','InitialMagnification',100)

        end

        % Value changed function: UnitsListBox_aco
        function UnitsListBox_acoValueChanged(app, event)
            units_aco(app);
        end

        % Value changed function: MediumListBox
        function MediumListBoxValueChanged(app, event)
            units_aco(app);
        end

        % Button pushed function: CalculateButton_1D_Pipe
        function CalculateButton_1D_PipePushed(app, event)
            aco_speed(app);

            L=str2num(app.LengthinEditField_1D_Pipe.Value);
    
            modal_density=L/(pi*app.c);
            app.mph=modal_density*app.tpi;
    
      
            s1=sprintf(' Modal Density \n\n  = %8.4g (modes/rad)\n',modal_density);
            s2=sprintf('  = %8.4g (modes/Hz)',app.mph);

            app.ResultsReadOnlyTextArea_1D_Pipe.Value=sprintf('%s%s',s1,s2);
    
    
        end

        % Button pushed function: CalculateButton_2D_Rect
        function CalculateButton_2D_RectPushed(app, event)
            aco_speed(app);

            L=str2num(app.LengthinEditField_2D_Rect.Value);    
            W=str2num(app.WidthinEditField_2D_Rect.Value);
           
            A=L*W;
            P=2*(L*W);

            for i=1:length(app.fc)
                app.mph(i)=(app.tpi*app.fc(i)*A/app.c^2)+(P/app.c);
            end
        
            app.stitle='Acoustic Cavity Rectangular 2D  Modal Density';

            plot_mph(app);

            name=app.OutputArrayNameEditField_2D_Rect.Value;
            assignin('base',name, [app.fc app.mph]);

        end

        % Button pushed function: CalculateButton_3D_Vol
        function CalculateButton_3D_VolPushed(app, event)
            aco_speed(app);

            L=str2num(app.LengthinEditField_3D_Vol.Value);    
            W=str2num(app.WidthinEditField_3D_Vol.Value); 
            H=str2num(app.HeightinEditField_3D_Vol.Value);     
            
            V=L*W*H;
            A=2*(L*W+L*H+W*H);
            P=4*(L+W+H); 
            
            D1=4*pi*V/app.c^3;
            D2=pi*A/(2*app.c^2);
            D3=P/(8*app.c);
            
            dd=@(f)(D1*f^2+D2*f+D3);
            
            for i=1:length(app.fc)
                
                app.mph(i)=dd(app.fc(i));
                
            end
            
            app.stitle='Acoustic Cavity Rectangular Prism  Modal Density';    
                            

            plot_mph(app);

            name=app.OutputArrayNameEditField_3D_Vol.Value;
            assignin('base',name, [app.fc app.mph]);
        end

        % Button pushed function: CalculateButton_3D_Cyl
        function CalculateButton_3D_CylPushed(app, event)
            aco_speed(app);

            H=str2num(app.HeightinEditField_3D_Cyl.Value);    
            r=str2num(app.RadiusinEditField_3D_Cyl.Value);  
            
            V=pi*r^2*H;
            
            D=V/app.c^3;   

            dd=@(D,f)(4*pi*D*f^2);
            
           
            for i=1:length(app.fc)
                
                app.mph(i)=dd(D,app.fc(i));  
                
            end
            
            app.stitle='Acoustic Cylinder 3D  Modal Density';              


            name=app.OutputArrayNameEditField_3D_Cyl.Value;
            assignin('base',name, [app.fc app.mph]);

            plot_mph(app);

        end

        % Button pushed function: CalculateButton_3D_Sphere
        function CalculateButton_3D_SpherePushed(app, event)
            aco_speed(app);

            r=str2num(app.RadiusinEditField_3D_Sphere.Value);  
            
            V=(4/3)*pi*r^3;
            
            D=V/app.c^3;   
            
              
            for i=1:length(app.fc)
                
                [app.mph(i)]=acoustic_volume_mdens(D,app.fc(i));  
                
            end
            
            app.stitle='Acoustic Sphere  Modal Density';
            

            name=app.OutputArrayNameEditField_3D_Sphere.Value;
            assignin('base',name, [app.fc app.mph]);

            plot_mph(app);
        end

        % Button pushed function: CalculateButton_3D_Other
        function CalculateButton_3D_OtherPushed(app, event)
            aco_speed(app);
            
            disp(' Acoustic Volume ');
    
            V=str2num(app.Volumein3EditField.Value);  
                
            D=V/app.c^3;   
                
            for i=1:length(app.fc)
                    
                [app.mph(i)]=acoustic_volume_mdens(D,app.fc(i));  
                          
            end
                
            app.stitle='Acoustic Volume  Modal Density';

            name=app.OutputArrayNameEditField_3D_Other.Value;

            assignin('base',name, [app.fc app.mph]);
            
            plot_mph(app);
        end

        % Value changing function: LengthinEditField_1D_Pipe
        function LengthinEditField_1D_PipeValueChanging(app, event)

            app.ResultsReadOnlyTextArea_1D_Pipe.Value='';
            CloseFigures(app);
        end

        % Value changing function: SpeedofSoundinsecEditField
        function SpeedofSoundinsecEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_1D_Pipe.Value='';
            CloseFigures(app);
        end

        % Value changing function: TemperaturedegCEditField
        function TemperaturedegCEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_1D_Pipe.Value='';
            CloseFigures(app);
        end

        % Value changing function: LengthinEditField_2D_Rect
        function LengthinEditField_2D_RectValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: WidthinEditField_2D_Rect
        function WidthinEditField_2D_RectValueChanging(app, event)
 
            CloseFigures(app);
        end

        % Value changing function: LengthinEditField_3D_Vol
        function LengthinEditField_3D_VolValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: WidthinEditField_3D_Vol
        function WidthinEditField_3D_VolValueChanging(app, event)
 
            CloseFigures(app);
        end

        % Value changing function: HeightinEditField_3D_Vol
        function HeightinEditField_3D_VolValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: Volumein3EditField
        function Volumein3EditFieldValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: RadiusinEditField_3D_Sphere
        function RadiusinEditField_3D_SphereValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: RadiusinEditField_3D_Cyl
        function RadiusinEditField_3D_CylValueChanging(app, event)
            CloseFigures(app);
        end

        % Value changing function: HeightinEditField_3D_Cyl
        function HeightinEditField_3D_CylValueChanging(app, event)

            CloseFigures(app);
        end

        % Value changed function: UnitsListBox_str
        function UnitsListBox_strValueChanged(app, event)
         
            units_str(app);
        end

        % Button pushed function: ViewEquationButton_7
        function ViewEquationButton_7Pushed(app, event)
            A = imread('beam_longitudinal_md.jpg');
            figure(998) 
            imshow(A,'border','tight','InitialMagnification',100) 
        end

        % Button pushed function: CalculateButton_rod
        function CalculateButton_rodPushed(app, event)
            
            preprocess_structure(app);

            L=str2num(app.LengthinEditField_rod.Value);

            modal_density=L/(pi*app.c);
            app.mph=modal_density*app.tpi;
            
            s1=sprintf(' Phase Velocity \n  = %8.4g %s \n\n Modal Density \n',app.c,app.su);
              
            s2=sprintf('  = %8.4g (modes/rad) \n',modal_density);
            s3=sprintf('  = %8.4g (modes/Hz)',app.mph);

            ss=sprintf('%s%s%s',s1,s2,s3);

            app.ResultsReadOnlyTextArea_1D_Rod.Value=ss;
            
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            units_str(app);
            
        end

        % Button pushed function: ViewEquationButton_8
        function ViewEquationButton_8Pushed(app, event)
            if(app.imode_type==1)
                A = imread('beam_bending_md.jpg');
                figure(997) 
                imshow(A,'border','tight','InitialMagnification',100)
            else
                A = imread('beam_torsional_md.jpg');
                figure(999) 
                imshow(A,'border','tight','InitialMagnification',100)  
            end
        end

        % Button pushed function: ViewEquationButton_9
        function ViewEquationButton_9Pushed(app, event)
            
            Items = strtrim(app.StructureListBox.Items);
            im = strtrim(app.StructureListBox.Value);
            app.istructure = find(strcmp(Items, im));

            if(app.istructure<=4)
                A = imread('Plate_bending_md.jpg');
                figure(998) 
            else
                A = imread('Plate_inplate_md.jpg');
                figure(999)  
            end
            imshow(A,'border','tight','InitialMagnification',100);
        end

        % Button pushed function: CalculateButton_plate_rect
        function CalculateButton_plate_rectPushed(app, event)
            preprocess_structure(app);

            Items = strtrim(app.StructureListBox.Items);
            im = strtrim(app.StructureListBox.Value);
            app.istructure = find(strcmp(Items, im));
            ns=app.istructure;

            L=str2num(app.LengthinEditField_rect.Value);
            W=str2num(app.WidthinEditField_rect.Value);
            H=str2num(app.ThicknessinEditField_rect.Value);

            if(app.ius==2)
                H=H/1000;
            end
            
            if(ns<=4)
                    
                area=L*W;
                
                [~,modal_density_rad,~,~]=panel_generic_modal_density(app.em,app.mu,app.md,H,area);
                
            end
            
            
            if(ns==1) % bending, generic
                
                app.stitle='Plate Bending, Generic BC';  
                disp(app.stitle);    
                    
                app.mph=modal_density_rad*app.tpi*ones(length(app.fc),1);      
               
            else
                mpr=zeros(length(app.fc),1);    
            end
            
            if(ns>=2 && ns<=4)
               
                if(ns==2)
                    app.stitle='Plate Bending, Simply-Supported';   
                end
                if(ns==3)
                    app.stitle='Plate Bending, Free';     
                end
                if(ns==4)
                    app.stitle='Plate Bending, Clamped';     
                end
                disp(app.stitle);       
                
                thick=H;
                
                for i=1:length(app.fc)    
            
                    if(ns==2)  % SS
                        [app.mph(i),mpr(i),~]=panel_modal_density_bc(app.em,app.mu,app.md,thick,L,W,app.fc(i),1);
                    end
                    if(ns==3)  % Free 
                        [app.mph(i),mpr(i),~]=panel_modal_density_bc(app.em,app.mu,app.md,thick,L,W,app.fc(i),2);
                    end
                    if(ns==4)  % Clamped
                        [app.mph(i),mpr(i),~]=panel_modal_density_bc(app.em,app.mu,app.md,thick,L,W,app.fc(i),3);
                    end    
                end
                
               
            end
            
            if(ns==5) % in-plane
                    app.stitle='Plate In-plane';      
                    disp(app.stitle);
                    
                    Eeff=app.em/(1-app.mu^2);
                    
                    app.c=sqrt(Eeff/app.md);
                    
                    for i=1:length(app.fc)             
                        app.mph(i)=app.tpi*L*W*app.fc(i)/app.c^2;
                    end  
                       
            end
            if(ns>=1)  % do for all
            
                app.fc=fix_size(app.fc);
                app.mph=fix_size(app.mph);    
                
                figure(1);
                plot(app.fc,app.mph);
                title(app.stitle);
               
                xlabel('Frequency (Hz)');
                ylabel('Modal Density (modes/Hz)');
                
                [xtt,xTT,iflag]=xtick_label(app.fmin,app.fmax);
            
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end    
               
                xlim([20,20000]);
                
                
                ya=get(gca,'YLim');
                
                ya=max(ya);
                
                mm=max(app.mph);
                
                if(mm<1)
                    ya=1;
                end
                if(mm<0.5)
                    ya=0.5; 
                end   
                if(mm<0.2)
                    ya=0.2;
                end    
                if(mm<0.1)
                    ya=0.1;
                end    
                if(mm<0.05)
                    ya=0.05;
                end   
                if(mm<0.02)
                    ya=0.02;
                end     
                if(mm<0.01)
                    ya=0.01;
                end     
                
                ylim([0,ya]);
                
                 
                set(gca,'XGrid','on','GridLineStyle',':');
                set(gca,'YGrid','on','GridLineStyle',':');    
                
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log',...
                                 'YScale','lin','XminorTick','off','YminorTick','off');   
                             
                             
                disp(' ');
                disp('  One-third Octave ');
                disp('  ');
                disp('  Center  Modal ');
                disp('  Freq    Density ');
                disp('  (Hz)    (modes/Hz) ');
                             
                for i=1:length(app.fc)
                    fprintf(' %6.0f  %8.4g \n',app.fc(i),app.mph(i));
                end
                
                app.fc=fix_size(app.fc);
                app.mph=fix_size(app.mph);
                
                plate_modal_density=[app.fc app.mph];
                
            %    size(plate_modal_density[Enter your work phone number
            %    here]
                
                s1=sprintf('Phase Velocity \n = %8.4g %s \n\n',app.c,app.su);

                s2=sprintf('Max Modal Density \n = %8.4g modes/Hz \n\n',max(app.mph));

                s3=sprintf('Min Modal Density \n = %8.4g modes/Hz',min(app.mph));

                app.ResultsReadOnlyTextArea_rect.Value=sprintf('%s%s%s',s1,s2,s3);

                name=app.OutputArrayNameEditField_rplate.Value;
                assignin('base', name, plate_modal_density);    
            end


        end

        % Button pushed function: GotoWaveMethodButton
        function GotoWaveMethodButtonPushed(app, event)
            cylinder_fn_a;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethod]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g',[1 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            
            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.');
                return;
            end
   
            freq=app.THM(:,2);
            
            
            [fl,app.fc,fu,imax]=one_third_octave_frequencies();
            
            n=length(freq);
            
            mdens=zeros(imax,1);
            nmodes=zeros(imax,1);
            
            for i=1:n
                
                for j=1:imax
                    
                    if(freq(i)>=fl(j) && freq(i)<=fu(j))
                    
                        nmodes(j)=nmodes(j)+1;
                        
                        break;
                    
                    end
                    
                end
                
            end    
            
            
            for j=imax:-1:1
                if(nmodes(j)==0 || app.fc(j)<10.)
                    fl(j)=[];
                    app.fc(j)=[];
                    fu(j)=[];
                    mdens(j)=[];
                    nmodes(j)=[];
                else
                    bw=app.fc(j)-fl(j);
                    mdens(j)=nmodes(j)/bw;
                end
            end
            
            fig_num=1;
            
            app.fc=fix_size(app.fc);
            mdens=fix_size(mdens);
            
            ppp=[app.fc,mdens];
            mmd=3;
            x_label='Center Frequency (Hz)';
            y_label='n (modes/Hz)';
            t_string='Modal Density';
            app.fmin=20;
            app.fmax=10000;
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,mmd);
            
            disp('  ');
            disp('   fc     Modal Density ');
            disp('  (Hz)     (modes/Hz)   ');
            for j=1:length(app.fc)
                out1=sprintf(' %8.1f  %8.4f  ',app.fc(j),mdens(j));
                disp(out1);
            end    
            
            disp('  ');
            output_name=app.OutputArrayNameEditField_modal_density.Value;
            assignin('base', output_name, ppp);

        end

        % Value changing function: LengthinEditField_rod
        function LengthinEditField_rodValueChanging(app, event)

            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
            app.ResultsReadOnlyTextArea_1D_Rod.Value='';
        end

        % Button pushed function: CalculateButton_beam
        function CalculateButton_beamPushed(app, event)
            preprocess_structure(app);

            Items = strtrim(app.CrossSectionListBox.Items);
            im = strtrim(app.CrossSectionListBox.Value);
            app.icross = find(strcmp(Items, im));     

            Items = strtrim(app.ModeTypeListBox.Items);
            im = strtrim(app.ModeTypeListBox.Value);
            app.imode_type = find(strcmp(Items, im)); 

            B=char(app.UITable.Data);

            sz=size(B);
            N=1+(sz(1)/2);

            L=str2num(B(N,:));

            if(app.imode_type==1) % bending

                if(app.icross==1) % rect
 
                    W=str2num(B(N+1,:));
                    H=str2num(B(N+2,:));

                    if(app.ius==2)
                       H=H/1000;
                    end
                    
                    I=(1/12)*W*H^3;
                    A=W*H;

                end 
                if(app.icross==2) % pipe
   
                    OD=str2num(B(N+1,:));
                    ID=str2num(B(N+2,:));

                    r1=0.5*max([OD ID]);
                    r2=0.5*min([OD ID]);                  
                    
                    I=(pi/4)*(r1^4-r2^4);
                    A=pi*(r1^2-r2^2);

                end 
                if(app.icross==3) % solid cyl

                    diam=str2num(B(N+1,:));

                    r1=0.5*diam;

                    I=(pi/4)*(r1^4);
                    A=pi*(r1^2);

                end                 
                if(app.icross==4) % other
      
                    A=str2num(B(N+1,:));
                    I=str2num(B(N+2,:));          
                end                

                fl=length(app.fc);
                
                app.mph=zeros(fl,1);

                for i=1:fl
                    [beam_md,~]=sea_beam_modal_density(I,A,app.em,app.md,app.fc(i),L);
                    app.mph(i)=beam_md;
                end

            else % torsion

                G=app.em/(2*(1+app.mu));

                if(app.icross==1) % rect

                    W=str2num(B(N+1,:));
                    H=str2num(B(N+2,:));

                    if(app.ius==2)
                       H=H/1000;
                    end
                    
                    b=W; 
                    h=H;
                             
                    Ip=(b*h/3)*(b^2+h^2);

                    a=max([b h]);
                    b=min([b h]);
                    r=b/a;
                     
                    J=(a*b^3)*((1/3)-0.21*r*(1-((r^4)/12)));  % wikipedia torsion constant 
                    app.c=sqrt(G*J/(app.md*Ip));                  

                end 
                if(app.icross==2 || app.icross==3) % pipe or solid cyl
                    app.c=sqrt(G/app.md); 
                end                 
                if(app.icross==4) % other
                    Ip=str2num(B(N+1,:));
                    Ix=str2num(B(N+2,:));
                    Iy=str2num(B(N+3,:));

                    J=4*Ix*Iy/(Ix+Iy);
                    app.c=sqrt(G*J/(app.md*Ip));

                end        

                modal_density=L/(pi*app.c);
                mt=modal_density*app.tpi;

                app.mph=ones(length(app.fc),1)*mt;

            end    

            name=app.OutputArrayNameEditField_beam.Value;
            assignin('base', name, [app.fc app.mph]); 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
            fig_num=1;
            ppp=[app.fc app.mph];
            
            x_label='Frequency (Hz)';
            y_label='Modal Density (modes/Hz)';

            mmd=6;

            if(app.imode_type==1)
                t_string='Beam Bending Modal Density'; 
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,mmd);
                ss=sprintf('Modal Density (Modes/Hz) \n\n Max = %8.4g \n Min = %8.4g ',max(app.mph),min(app.mph));
            else
                t_string='Beam Torsion Modal Density';
                [fig_num,h2]=plot_loglin_function_h2(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax);
                ss=sprintf('Modal Density \n = %8.4g modes/Hz',mean(app.mph));
            end


            app.ResultsReadOnlyTextArea_beam.Value=ss;

        end

        % Button pushed function: CalculateButton_plate_circ
        function CalculateButton_plate_circPushed(app, event)
            preprocess_structure(app);

            Sdiam = app.DiameterinEditField_circular.Value;
            if isempty(Sdiam)
              warndlg('Enter Diameter');  
              return;
            else
                diam=str2num(Sdiam);  
            end
            
            
            Sthick = app.ThickinEditField_circular.Value;
            if isempty(Sthick)
              warndlg('Enter Thickness');  
              return;
            else
                thick=str2num(Sthick);  
            end
            
            if(app.ius==2)
                thick=thick/1000;
            end
             
            a=diam/2;
            radius=a;

            
            if(app.igeo_circular==1)
                t_string='Modal Density  Circular Plate';
                [app.mph]=circular_plate_mdens(radius,thick,app.md,app.em,app.mu,app.fc);
            else
                outer_diam=diam;
                t_string='Modal Density  Annular Plate';
                inner_diam=str2num(app.DiameterinEditField_circular_inner.Value);
                if(isempty(inner_diam))
                    warndlg('Enter inner diameter');
                    return;
                end

                if(inner_diam>=outer_diam)
                    warndlg('Diameter error'); 
                    return; 
                end
        
                [app.mph]=annular_plate_mdens(outer_diam,inner_diam,thick,app.md,app.em,app.mu,app.fc); 
          
            end

                disp(' ');
                disp(' Circular Plate Modal Density ');
                disp(' Ref:  NASA CR-1773 (page 35) ');
                
                disp(' ');
                disp('  One-third Octave ');
                disp('  ');
                disp('  Center  Modal ');
                disp('  Freq    Density ');
                disp('  (Hz)    (modes/Hz) ');
                             
                for i=1:length(app.fc)
                    fprintf(' %6.0f  %8.4g \n',app.fc(i),app.mph(i));
                end
                
                modal_density=[app.fc app.mph];

            mx=mean(app.mph);

            s1=sprintf(' Modal Density \n\n  = %8.4g (modes/rad)\n',mx/app.tpi);
            s2=sprintf('  = %8.4g (modes/Hz)',mx);    
            ss=sprintf('%s%s',s1,s2);
                
            app.ResultsReadOnlyTextArea_circular.Value=ss;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            x_label='Frequency (Hz)';
            y_label='Modal Density (modes/Hz)';
            
            ppp=modal_density;
            
            [fig_num,h2]=...
               plot_loglin_function_h2(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax);

            name=app.OutputArrayNameEditField_circular.Value;
            assignin('base', name, modal_density); 
                        
          
        end

        % Button pushed function: CalculateButton_cyl_sze
        function CalculateButton_cyl_szePushed(app, event)
            preprocess_structure(app);

            SL = app.LengthinEditField_cyl.Value;
            if isempty(SL)
              warndlg('Enter Length');  
              return;
            else
                L=str2num(SL);  
            end
            
            Sdiam = app.DiameterinEditField_cyl.Value;
            if isempty(Sdiam)
              warndlg('Enter Diameter');  
              return;
            else
                d=str2num(Sdiam);  
            end
            
            
            Sthick = app.ThickinEditField_cyl.Value;
            if isempty(Sthick)
              warndlg('Enter Thickness');  
              return;
            else
                t=str2num(Sthick);  
            end
            
            
            if(app.ius==2)
                t=t/1000;
            end
            
            [fr,CL]=ring_frequency_I(app.em,app.md,d);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
 
            for i=1:length(app.fc)
                [app.mph(i)] = cylinder_mdens(app.fc(i),fr,L,t);     
            end        
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp('* * * *');
            
            disp(' ');
            disp(' Unstiffened Cylindrical Shell');
            
            s1=sprintf('Speed of Sound = \n %8.4g %s \n\n',CL,app.su);
            s2=sprintf('Ring Frequency = \n %8.4g Hz',fr);

            app.ResultsReadOnlyTextArea_cyl.Value=sprintf('%s%s',s1,s2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                figure(1);
                plot(app.fc,app.mph);
                title('Unstiffened Cylinder Modal Density');
               
                xlabel('Frequency (Hz)');
                ylabel('Modal Density (modes/Hz)');
                
                [xtt,xTT,iflag]=xtick_label(app.fmin,app.fmax);
            
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                end    
                
                ymin=10^(floor(log10(min(app.mph))));
                ymax=10^(ceil(log10(max(app.mph))));
               
                xlim([20,20000]);
                ylim([ ymin,ymax]);
                
                set(gca,'XGrid','on','GridLineStyle',':');
                set(gca,'YGrid','on','GridLineStyle',':');    
                
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log',...
                                 'YScale','log','XminorTick','off','YminorTick','off');   
                             
                             
                disp(' ');
                disp('  One-third Octave ');
                disp('  ');
                disp('  Center  Modal ');
                disp('  Freq    Density ');
                disp('  (Hz)    (modes/Hz) ');
                             
                for i=1:length(app.fc)
                    fprintf(' %6.0f  %8.4g \n',app.fc(i),app.mph(i));
                end
                
                modal_density=[app.fc app.mph];
                
                name=app.OutputArrayNameEditField_cyl.Value;
                assignin('base', name, modal_density); 
            
         
        end

        % Callback function
        function CalculateButton_annularPushed(app, event)
            preprocess_structure(app);
        end

        % Value changing function: LengthinEditField_cyl
        function LengthinEditField_cylValueChanging(app, event)
            app.ResultsReadOnlyTextArea_cyl.Value='';
        end

        % Value changing function: ThickinEditField_cyl
        function ThickinEditField_cylValueChanging(app, event)
            app.ResultsReadOnlyTextArea_cyl.Value='';
        end

        % Value changing function: DiameterinEditField_cyl
        function DiameterinEditField_cylValueChanging(app, event)
            app.ResultsReadOnlyTextArea_cyl.Value='';
        end

        % Value changing function: DiameterinEditField_circular
        function DiameterinEditField_circularValueChanging(app, event)
            app.ResultsReadOnlyTextArea_circular.Value='';
        end

        % Value changing function: ThickinEditField_circular
        function ThickinEditField_circularValueChanging(app, event)
            app.ResultsReadOnlyTextArea_circular.Value='';
        end

        % Button pushed function: ViewEquationButton_10
        function ViewEquationButton_10Pushed(app, event)
            A = imread('Circular_plate_bending_md.jpg');
            figure(998) 
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Value changed function: UnitsListBox_circular_geometry
        function UnitsListBox_circular_geometryValueChanged(app, event)
            circular_geometry(app);
        end

        % Value changed function: DiameterinEditField_circular
        function DiameterinEditField_circularValueChanged(app, event)
            app.ResultsReadOnlyTextArea_circular.Value='';
            
        end

        % Value changed function: DiameterinEditField_circular_inner
        function DiameterinEditField_circular_innerValueChanged(app, event)
            app.ResultsReadOnlyTextArea_circular.Value='';
            
        end

        % Value changing function: DiameterinEditField_circular_inner
        function DiameterinEditField_circular_innerValueChanging(app, event)
            app.ResultsReadOnlyTextArea_circular.Value='';
            
        end

        % Value changed function: StructureListBox
        function StructureListBoxValueChanged(app, event)
            
            app.ResultsReadOnlyTextArea_rect.Value='';
        end

        % Value changing function: LengthinEditField_rect
        function LengthinEditField_rectValueChanging(app, event)
    
            app.ResultsReadOnlyTextArea_rect.Value='';
        end

        % Value changing function: WidthinEditField_rect
        function WidthinEditField_rectValueChanging(app, event)
            
            app.ResultsReadOnlyTextArea_rect.Value='';
        end

        % Value changed function: ThicknessinEditField_rect
        function ThicknessinEditField_rectValueChanged(app, event)
            
            app.ResultsReadOnlyTextArea_rect.Value='';
        end

        % Value changed function: ModeTypeListBox
        function ModeTypeListBoxValueChanged(app, event)
            units_str(app);
            
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)


            units_str(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            app.ResultsReadOnlyTextArea_beam.Value='';
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
   
            app.ResultsReadOnlyTextArea_beam.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.modaldensityUIFigure = uifigure('Visible', 'off');
            app.modaldensityUIFigure.Name = 'modal density';
            app.modal_density_aver10byTomIrvineLabel = uilabel(app.modaldensityUIFigure);
            app.modal_density_aver10byTomIrvineLabel.Text = 'modal_density_a   ver 1.0  by Tom Irvine   ';
            app.ReturnButton = uibutton(app.modaldensityUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup2 = uitabgroup(app.modaldensityUIFigure);
            app.AirborneAcousticalTab = uitab(app.TabGroup2);
            app.AirborneAcousticalTab.Title = 'Airborne Acoustical';
            app.UnitsListBoxLabel = uilabel(app.AirborneAcousticalTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox_aco = uilistbox(app.AirborneAcousticalTab);
            app.UnitsListBox_aco.Items = {'English - inch', 'English - feet', 'metric'};
            app.UnitsListBox_aco.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_acoValueChanged, true);
            app.UnitsListBox_aco.Value = 'English - inch';
            app.TabGroup5 = uitabgroup(app.AirborneAcousticalTab);
            app.DPipeTab = uitab(app.TabGroup5);
            app.DPipeTab.Title = '1D Pipe';
            app.CalculateButton_1D_Pipe = uibutton(app.DPipeTab, 'push');
            app.CalculateButton_1D_Pipe.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_1D_PipePushed, true);
            app.CalculateButton_1D_Pipe.Text = 'Calculate';
            app.ViewEquationButton = uibutton(app.DPipeTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.LengthinEditFieldLabel = uilabel(app.DPipeTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField_1D_Pipe = uieditfield(app.DPipeTab, 'text');
            app.LengthinEditField_1D_Pipe.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_1D_PipeValueChanging, true);
            app.ResultsReadOnlyTextAreaLabel = uilabel(app.DPipeTab);
            app.ResultsReadOnlyTextAreaLabel.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_1D_Pipe = uitextarea(app.DPipeTab);
            app.DRectangleTab = uitab(app.TabGroup5);
            app.DRectangleTab.Title = '2D Rectangle';
            app.CalculateButton_2D_Rect = uibutton(app.DRectangleTab, 'push');
            app.CalculateButton_2D_Rect.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2D_RectPushed, true);
            app.CalculateButton_2D_Rect.Text = 'Calculate';
            app.ViewEquationButton_2 = uibutton(app.DRectangleTab, 'push');
            app.ViewEquationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_2Pushed, true);
            app.ViewEquationButton_2.Text = 'View Equation';
            app.LengthinEditField_2Label = uilabel(app.DRectangleTab);
            app.LengthinEditField_2Label.Text = 'Length (in)';
            app.LengthinEditField_2D_Rect = uieditfield(app.DRectangleTab, 'text');
            app.LengthinEditField_2D_Rect.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_2D_RectValueChanging, true);
            app.WidthinEditFieldLabel = uilabel(app.DRectangleTab);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField_2D_Rect = uieditfield(app.DRectangleTab, 'text');
            app.WidthinEditField_2D_Rect.ValueChangingFcn = createCallbackFcn(app, @WidthinEditField_2D_RectValueChanging, true);
            app.OutputArrayNameEditFieldLabel = uilabel(app.DRectangleTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField_2D_Rect = uieditfield(app.DRectangleTab, 'text');
            app.OutputArrayNameEditField_2D_Rect.Value = 'modal_dens_2D_rec';
            app.DRectangularVolumeTab = uitab(app.TabGroup5);
            app.DRectangularVolumeTab.Title = '3D Rectangular Volume';
            app.CalculateButton_3D_Vol = uibutton(app.DRectangularVolumeTab, 'push');
            app.CalculateButton_3D_Vol.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3D_VolPushed, true);
            app.CalculateButton_3D_Vol.Text = 'Calculate';
            app.ViewEquationButton_3 = uibutton(app.DRectangularVolumeTab, 'push');
            app.ViewEquationButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_3Pushed, true);
            app.ViewEquationButton_3.Text = 'View Equation';
            app.LengthinEditField_3Label = uilabel(app.DRectangularVolumeTab);
            app.LengthinEditField_3Label.Text = 'Length (in)';
            app.LengthinEditField_3D_Vol = uieditfield(app.DRectangularVolumeTab, 'text');
            app.LengthinEditField_3D_Vol.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_3D_VolValueChanging, true);
            app.WidthinEditField_2Label = uilabel(app.DRectangularVolumeTab);
            app.WidthinEditField_2Label.Text = 'Width (in)';
            app.WidthinEditField_3D_Vol = uieditfield(app.DRectangularVolumeTab, 'text');
            app.WidthinEditField_3D_Vol.ValueChangingFcn = createCallbackFcn(app, @WidthinEditField_3D_VolValueChanging, true);
            app.HeightinEditFieldLabel = uilabel(app.DRectangularVolumeTab);
            app.HeightinEditFieldLabel.Text = 'Height (in)';
            app.HeightinEditField_3D_Vol = uieditfield(app.DRectangularVolumeTab, 'text');
            app.HeightinEditField_3D_Vol.ValueChangingFcn = createCallbackFcn(app, @HeightinEditField_3D_VolValueChanging, true);
            app.OutputArrayNameEditFieldLabel_2 = uilabel(app.DRectangularVolumeTab);
            app.OutputArrayNameEditFieldLabel_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_3D_Vol = uieditfield(app.DRectangularVolumeTab, 'text');
            app.OutputArrayNameEditField_3D_Vol.Value = 'modal_dens_3D_vol';
            app.DCylinderTab = uitab(app.TabGroup5);
            app.DCylinderTab.Title = '3D Cylinder';
            app.CalculateButton_3D_Cyl = uibutton(app.DCylinderTab, 'push');
            app.CalculateButton_3D_Cyl.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3D_CylPushed, true);
            app.CalculateButton_3D_Cyl.Text = 'Calculate';
            app.ViewEquationButton_4 = uibutton(app.DCylinderTab, 'push');
            app.ViewEquationButton_4.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_4Pushed, true);
            app.ViewEquationButton_4.Text = 'View Equation';
            app.HeightinEditField_2Label = uilabel(app.DCylinderTab);
            app.HeightinEditField_2Label.Text = 'Height (in)';
            app.HeightinEditField_3D_Cyl = uieditfield(app.DCylinderTab, 'text');
            app.HeightinEditField_3D_Cyl.ValueChangingFcn = createCallbackFcn(app, @HeightinEditField_3D_CylValueChanging, true);
            app.RadiusinEditFieldLabel = uilabel(app.DCylinderTab);
            app.RadiusinEditFieldLabel.Text = 'Radius (in)';
            app.RadiusinEditField_3D_Cyl = uieditfield(app.DCylinderTab, 'text');
            app.RadiusinEditField_3D_Cyl.ValueChangingFcn = createCallbackFcn(app, @RadiusinEditField_3D_CylValueChanging, true);
            app.OutputArrayNameEditFieldLabel_3 = uilabel(app.DCylinderTab);
            app.OutputArrayNameEditFieldLabel_3.Text = 'Output Array Name';
            app.OutputArrayNameEditField_3D_Cyl = uieditfield(app.DCylinderTab, 'text');
            app.OutputArrayNameEditField_3D_Cyl.Value = 'modal_dens_3D_cyl';
            app.DSphereTab = uitab(app.TabGroup5);
            app.DSphereTab.Title = '3D Sphere';
            app.CalculateButton_3D_Sphere = uibutton(app.DSphereTab, 'push');
            app.CalculateButton_3D_Sphere.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3D_SpherePushed, true);
            app.CalculateButton_3D_Sphere.Text = 'Calculate';
            app.ViewEquationButton_5 = uibutton(app.DSphereTab, 'push');
            app.ViewEquationButton_5.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_5Pushed, true);
            app.ViewEquationButton_5.Text = 'View Equation';
            app.RadiusinEditField_2Label = uilabel(app.DSphereTab);
            app.RadiusinEditField_2Label.Text = 'Radius (in)';
            app.RadiusinEditField_3D_Sphere = uieditfield(app.DSphereTab, 'text');
            app.RadiusinEditField_3D_Sphere.ValueChangingFcn = createCallbackFcn(app, @RadiusinEditField_3D_SphereValueChanging, true);
            app.OutputArrayNameEditFieldLabel_4 = uilabel(app.DSphereTab);
            app.OutputArrayNameEditFieldLabel_4.Text = 'Output Array Name';
            app.OutputArrayNameEditField_3D_Sphere = uieditfield(app.DSphereTab, 'text');
            app.OutputArrayNameEditField_3D_Sphere.Value = 'modal_dens_3D_sphere';
            app.DOtherTab = uitab(app.TabGroup5);
            app.DOtherTab.Title = '3D Other';
            app.CalculateButton_3D_Other = uibutton(app.DOtherTab, 'push');
            app.CalculateButton_3D_Other.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3D_OtherPushed, true);
            app.CalculateButton_3D_Other.Text = 'Calculate';
            app.ViewEquationButton_6 = uibutton(app.DOtherTab, 'push');
            app.ViewEquationButton_6.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_6Pushed, true);
            app.ViewEquationButton_6.Text = 'View Equation';
            app.Volumein3EditFieldLabel = uilabel(app.DOtherTab);
            app.Volumein3EditFieldLabel.Text = 'Volume (in^3)';
            app.Volumein3EditField = uieditfield(app.DOtherTab, 'text');
            app.Volumein3EditField.ValueChangingFcn = createCallbackFcn(app, @Volumein3EditFieldValueChanging, true);
            app.OutputArrayNameEditFieldLabel_5 = uilabel(app.DOtherTab);
            app.OutputArrayNameEditFieldLabel_5.Text = 'Output Array Name';
            app.OutputArrayNameEditField_3D_Other = uieditfield(app.DOtherTab, 'text');
            app.OutputArrayNameEditField_3D_Other.Value = 'modal_dens_3D_other';
            app.MediumListBoxLabel = uilabel(app.AirborneAcousticalTab);
            app.MediumListBoxLabel.Text = 'Medium';
            app.MediumListBox = uilistbox(app.AirborneAcousticalTab);
            app.MediumListBox.Items = {'Air ', 'Other'};
            app.MediumListBox.ValueChangedFcn = createCallbackFcn(app, @MediumListBoxValueChanged, true);
            app.MediumListBox.Value = 'Air ';
            app.TemperaturedegCEditFieldLabel = uilabel(app.AirborneAcousticalTab);
            app.TemperaturedegCEditFieldLabel.Text = 'Temperature deg C';
            app.TemperaturedegCEditField = uieditfield(app.AirborneAcousticalTab, 'text');
            app.TemperaturedegCEditField.ValueChangingFcn = createCallbackFcn(app, @TemperaturedegCEditFieldValueChanging, true);
            app.TemperaturedegCEditField.Value = '20';
            app.SpeedofSoundinsecEditFieldLabel = uilabel(app.AirborneAcousticalTab);
            app.SpeedofSoundinsecEditFieldLabel.Text = 'Speed of Sound (in/sec)';
            app.SpeedofSoundinsecEditField = uieditfield(app.AirborneAcousticalTab, 'text');
            app.SpeedofSoundinsecEditField.ValueChangingFcn = createCallbackFcn(app, @SpeedofSoundinsecEditFieldValueChanging, true);
            app.StructuralTab = uitab(app.TabGroup2);
            app.StructuralTab.Title = 'Structural';
            app.UnitsListBox_2Label = uilabel(app.StructuralTab);
            app.UnitsListBox_2Label.Text = 'Units';
            app.UnitsListBox_str = uilistbox(app.StructuralTab);
            app.UnitsListBox_str.Items = {'English', 'metric'};
            app.UnitsListBox_str.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_strValueChanged, true);
            app.UnitsListBox_str.Value = 'English';
            app.TabGroup = uitabgroup(app.StructuralTab);
            app.RodLongTab = uitab(app.TabGroup);
            app.RodLongTab.Title = 'Rod Long';
            app.CalculateButton_rod = uibutton(app.RodLongTab, 'push');
            app.CalculateButton_rod.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_rodPushed, true);
            app.CalculateButton_rod.Text = 'Calculate';
            app.LengthinEditFieldLabel_rod = uilabel(app.RodLongTab);
            app.LengthinEditFieldLabel_rod.Text = 'Length (in)';
            app.LengthinEditField_rod = uieditfield(app.RodLongTab, 'text');
            app.LengthinEditField_rod.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_rodValueChanging, true);
            app.ViewEquationButton_7 = uibutton(app.RodLongTab, 'push');
            app.ViewEquationButton_7.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_7Pushed, true);
            app.ViewEquationButton_7.Text = 'View Equation';
            app.ResultsReadOnlyTextAreaLabel_2 = uilabel(app.RodLongTab);
            app.ResultsReadOnlyTextAreaLabel_2.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_1D_Rod = uitextarea(app.RodLongTab);
            app.ResultsReadOnlyTextArea_1D_Rod.Editable = 'off';
            app.BeamTab = uitab(app.TabGroup);
            app.BeamTab.Title = 'Beam';
            app.ModeTypeListBoxLabel = uilabel(app.BeamTab);
            app.ModeTypeListBoxLabel.Text = 'Mode Type';
            app.ModeTypeListBox = uilistbox(app.BeamTab);
            app.ModeTypeListBox.Items = {'Bending', 'Torsional'};
            app.ModeTypeListBox.ValueChangedFcn = createCallbackFcn(app, @ModeTypeListBoxValueChanged, true);
            app.ModeTypeListBox.Value = 'Bending';
            app.CrossSectionListBoxLabel = uilabel(app.BeamTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.BeamTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Rectangular';
            app.UITable = uitable(app.BeamTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.CalculateButton_beam = uibutton(app.BeamTab, 'push');
            app.CalculateButton_beam.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_beamPushed, true);
            app.CalculateButton_beam.Text = 'Calculate';
            app.OutputArrayNameEditFieldLabel_10 = uilabel(app.BeamTab);
            app.OutputArrayNameEditFieldLabel_10.Text = 'Output Array Name';
            app.OutputArrayNameEditField_beam = uieditfield(app.BeamTab, 'text');
            app.OutputArrayNameEditField_beam.Value = 'modal_dens_beam';
            app.ViewEquationButton_8 = uibutton(app.BeamTab, 'push');
            app.ViewEquationButton_8.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_8Pushed, true);
            app.ViewEquationButton_8.Text = 'View Equation';
            app.ResultsReadOnlyTextAreaLabel_6 = uilabel(app.BeamTab);
            app.ResultsReadOnlyTextAreaLabel_6.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_beam = uitextarea(app.BeamTab);
            app.ResultsReadOnlyTextArea_beam.Editable = 'off';
            app.PlatesTab = uitab(app.TabGroup);
            app.PlatesTab.Title = 'Plates';
            app.TabGroup4 = uitabgroup(app.PlatesTab);
            app.RectangularTab = uitab(app.TabGroup4);
            app.RectangularTab.Title = 'Rectangular';
            app.CalculateButton_plate_rect = uibutton(app.RectangularTab, 'push');
            app.CalculateButton_plate_rect.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_plate_rectPushed, true);
            app.CalculateButton_plate_rect.Text = 'Calculate';
            app.ViewEquationButton_9 = uibutton(app.RectangularTab, 'push');
            app.ViewEquationButton_9.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_9Pushed, true);
            app.ViewEquationButton_9.Text = 'View Equation';
            app.StructureListBoxLabel = uilabel(app.RectangularTab);
            app.StructureListBoxLabel.Text = 'Structure';
            app.StructureListBox = uilistbox(app.RectangularTab);
            app.StructureListBox.Items = {'Bending, Generic BC     ', 'Bending, Simply-Supported', 'Bending, Free            ', 'Bending, Clamped         ', 'In-plane'};
            app.StructureListBox.ValueChangedFcn = createCallbackFcn(app, @StructureListBoxValueChanged, true);
            app.StructureListBox.Value = 'Bending, Generic BC     ';
            app.ResultsReadOnlyTextAreaLabel_4 = uilabel(app.RectangularTab);
            app.ResultsReadOnlyTextAreaLabel_4.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_rect = uitextarea(app.RectangularTab);
            app.ResultsReadOnlyTextArea_rect.Editable = 'off';
            app.LengthinEditFieldLabel_rect = uilabel(app.RectangularTab);
            app.LengthinEditFieldLabel_rect.Text = 'Length (in)';
            app.LengthinEditField_rect = uieditfield(app.RectangularTab, 'text');
            app.LengthinEditField_rect.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_rectValueChanging, true);
            app.WidthinEditFieldLabel_rect = uilabel(app.RectangularTab);
            app.WidthinEditFieldLabel_rect.Text = 'Width (in)';
            app.WidthinEditField_rect = uieditfield(app.RectangularTab, 'text');
            app.WidthinEditField_rect.ValueChangingFcn = createCallbackFcn(app, @WidthinEditField_rectValueChanging, true);
            app.ThicknessinEditFieldLabel_rect = uilabel(app.RectangularTab);
            app.ThicknessinEditFieldLabel_rect.Text = 'Thickness (in)';
            app.ThicknessinEditField_rect = uieditfield(app.RectangularTab, 'text');
            app.ThicknessinEditField_rect.ValueChangedFcn = createCallbackFcn(app, @ThicknessinEditField_rectValueChanged, true);
            app.OutputArrayNameEditFieldLabel_9 = uilabel(app.RectangularTab);
            app.OutputArrayNameEditFieldLabel_9.Text = 'Output Array Name';
            app.OutputArrayNameEditField_rplate = uieditfield(app.RectangularTab, 'text');
            app.OutputArrayNameEditField_rplate.Value = 'modal_dens_rplate';
            app.CircularAnnularTab = uitab(app.TabGroup4);
            app.CircularAnnularTab.Title = 'Circular & Annular';
            app.CalculateButton_plate_circ = uibutton(app.CircularAnnularTab, 'push');
            app.CalculateButton_plate_circ.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_plate_circPushed, true);
            app.CalculateButton_plate_circ.Text = 'Calculate';
            app.DiameterinEditFieldLabel_circular = uilabel(app.CircularAnnularTab);
            app.DiameterinEditFieldLabel_circular.Text = 'Diameter (in)';
            app.DiameterinEditField_circular = uieditfield(app.CircularAnnularTab, 'text');
            app.DiameterinEditField_circular.ValueChangedFcn = createCallbackFcn(app, @DiameterinEditField_circularValueChanged, true);
            app.DiameterinEditField_circular.ValueChangingFcn = createCallbackFcn(app, @DiameterinEditField_circularValueChanging, true);
            app.ThickinEditFieldLabel_circular = uilabel(app.CircularAnnularTab);
            app.ThickinEditFieldLabel_circular.Text = 'Thickness (in)';
            app.ThickinEditField_circular = uieditfield(app.CircularAnnularTab, 'text');
            app.ThickinEditField_circular.ValueChangingFcn = createCallbackFcn(app, @ThickinEditField_circularValueChanging, true);
            app.OutputArrayNameEditFieldLabel_8 = uilabel(app.CircularAnnularTab);
            app.OutputArrayNameEditFieldLabel_8.Text = 'Output Array Name';
            app.OutputArrayNameEditField_circular = uieditfield(app.CircularAnnularTab, 'text');
            app.OutputArrayNameEditField_circular.Value = 'modal_dens_cplate';
            app.ResultsReadOnlyTextAreaLabel_5 = uilabel(app.CircularAnnularTab);
            app.ResultsReadOnlyTextAreaLabel_5.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_circular = uitextarea(app.CircularAnnularTab);
            app.ResultsReadOnlyTextArea_circular.Editable = 'off';
            app.ViewEquationButton_10 = uibutton(app.CircularAnnularTab, 'push');
            app.ViewEquationButton_10.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButton_10Pushed, true);
            app.ViewEquationButton_10.Text = 'View Equation';
            app.DiameterinEditFieldLabel_circular_inner = uilabel(app.CircularAnnularTab);
            app.DiameterinEditFieldLabel_circular_inner.Text = 'Inner Diameter (in)';
            app.DiameterinEditField_circular_inner = uieditfield(app.CircularAnnularTab, 'text');
            app.DiameterinEditField_circular_inner.ValueChangedFcn = createCallbackFcn(app, @DiameterinEditField_circular_innerValueChanged, true);
            app.DiameterinEditField_circular_inner.ValueChangingFcn = createCallbackFcn(app, @DiameterinEditField_circular_innerValueChanging, true);
            app.UnitsListBox_2Label_2 = uilabel(app.CircularAnnularTab);
            app.UnitsListBox_2Label_2.Text = 'Geometry';
            app.UnitsListBox_circular_geometry = uilistbox(app.CircularAnnularTab);
            app.UnitsListBox_circular_geometry.Items = {'Circular', 'Annular'};
            app.UnitsListBox_circular_geometry.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_circular_geometryValueChanged, true);
            app.UnitsListBox_circular_geometry.Value = 'Circular';
            app.CylindricalShellTab = uitab(app.TabGroup);
            app.CylindricalShellTab.Title = 'Cylindrical Shell';
            app.TabGroup3 = uitabgroup(app.CylindricalShellTab);
            app.SzechenyiTab = uitab(app.TabGroup3);
            app.SzechenyiTab.Title = 'Szechenyi';
            app.CalculateButton_cyl_sze = uibutton(app.SzechenyiTab, 'push');
            app.CalculateButton_cyl_sze.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_cyl_szePushed, true);
            app.CalculateButton_cyl_sze.Text = 'Calculate';
            app.ViewEquationButton_cyl = uibutton(app.SzechenyiTab, 'push');
            app.ViewEquationButton_cyl.Text = 'View Equation';
            app.LengthinEditFieldLabel_cyl = uilabel(app.SzechenyiTab);
            app.LengthinEditFieldLabel_cyl.Text = 'Length (in)';
            app.LengthinEditField_cyl = uieditfield(app.SzechenyiTab, 'text');
            app.LengthinEditField_cyl.ValueChangingFcn = createCallbackFcn(app, @LengthinEditField_cylValueChanging, true);
            app.DiameterinEditFieldLabel_cyl = uilabel(app.SzechenyiTab);
            app.DiameterinEditFieldLabel_cyl.Text = 'Diameter (in)';
            app.DiameterinEditField_cyl = uieditfield(app.SzechenyiTab, 'text');
            app.DiameterinEditField_cyl.ValueChangingFcn = createCallbackFcn(app, @DiameterinEditField_cylValueChanging, true);
            app.ThickinEditFieldLabel_cyl = uilabel(app.SzechenyiTab);
            app.ThickinEditFieldLabel_cyl.Text = 'Wall Thickness (in)';
            app.ThickinEditField_cyl = uieditfield(app.SzechenyiTab, 'text');
            app.ThickinEditField_cyl.ValueChangingFcn = createCallbackFcn(app, @ThickinEditField_cylValueChanging, true);
            app.ResultsReadOnlyTextAreaLabel_3 = uilabel(app.SzechenyiTab);
            app.ResultsReadOnlyTextAreaLabel_3.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea_cyl = uitextarea(app.SzechenyiTab);
            app.ResultsReadOnlyTextArea_cyl.Editable = 'off';
            app.OutputArrayNameEditFieldLabel_7 = uilabel(app.SzechenyiTab);
            app.OutputArrayNameEditFieldLabel_7.Text = 'Output Array Name';
            app.OutputArrayNameEditField_cyl = uieditfield(app.SzechenyiTab, 'text');
            app.OutputArrayNameEditField_cyl.Value = 'modal_dens_cyl';
            app.WaveTab = uitab(app.TabGroup3);
            app.WaveTab.Title = 'Wave';
            app.GotoWaveMethodButton = uibutton(app.WaveTab, 'push');
            app.GotoWaveMethodButton.ButtonPushedFcn = createCallbackFcn(app, @GotoWaveMethodButtonPushed, true);
            app.GotoWaveMethodButton.Text = 'Go to Wave Method';
            app.HoneycombSandwichTab = uitab(app.TabGroup);
            app.HoneycombSandwichTab.Title = 'Honeycomb Sandwich';
            app.GotoHoneycombSandwichButton = uibutton(app.HoneycombSandwichTab, 'push');
            app.GotoHoneycombSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHoneycombSandwichButtonPushed, true);
            app.GotoHoneycombSandwichButton.Text = 'Go to Honeycomb Sandwich';
            app.MaterialListBoxLabel = uilabel(app.StructuralTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.StructuralTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.StructuralTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.StructuralTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.StructuralTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.StructuralTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.StructuralTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.StructuralTab, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.ConvertFreqArraytoModalDensityTab = uitab(app.TabGroup2);
            app.ConvertFreqArraytoModalDensityTab.Title = 'Convert Freq Array to Modal Density';
            app.listbox_method = uilistbox(app.ConvertFreqArraytoModalDensityTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ConvertFreqArraytoModalDensityTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ConvertFreqArraytoModalDensityTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ConvertFreqArraytoModalDensityTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ConvertFreqArraytoModalDensityTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.CalculateButton = uibutton(app.ConvertFreqArraytoModalDensityTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputArrayNameEditFieldLabel_6 = uilabel(app.ConvertFreqArraytoModalDensityTab);
            app.OutputArrayNameEditFieldLabel_6.Text = 'Output Array Name';
            app.OutputArrayNameEditField_modal_density = uieditfield(app.ConvertFreqArraytoModalDensityTab, 'text');
            app.OutputArrayNameEditField_modal_density.Value = 'modal_density';
            app.Label = uilabel(app.ConvertFreqArraytoModalDensityTab);
            app.Label.Text = {'This script converts a natural frequency array into a one-third octave modal density array.'; ''; 'The input array must have two columns:  index & fn(Hz)'};
            % Show the figure after all components are created
            app.modaldensityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modal_density_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.modaldensityUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.modaldensityUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.modaldensityUIFigure)
        end
    end
end