classdef msc_nastran_utility < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mscnastranUIFigure             matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Selectf06ArrayTab              matlab.ui.container.Tab
        ReadArrayButton                matlab.ui.control.Button
        EigenvaluesVectorsTab          matlab.ui.container.Tab
        TheoutputeigenvectorsnameswillbeappendedwiththemodenumberLabel  matlab.ui.control.Label
        EigenvectorsEditField          matlab.ui.control.EditField
        EigenvectorsEditFieldLabel     matlab.ui.control.Label
        EigenvaluesEditField           matlab.ui.control.EditField
        EigenvaluesEditFieldLabel      matlab.ui.control.Label
        OutputArrayNamesLabel          matlab.ui.control.Label
        NaturalFrequenciesHzEditField  matlab.ui.control.EditField
        NaturalFrequenciesHzEditFieldLabel  matlab.ui.control.Label
        ExtractButton                  matlab.ui.control.Button
        msc_nastran_utilityver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        fid % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ReadArrayButton
        function ReadArrayButtonPushed(app, event)
            try
                [filename, pathname] = uigetfile('*.f06');
                filename = fullfile(pathname, filename);
            catch
            end   
            app.fid = fopen(filename, 'r');
            if app.fid ~= -1
                msgbox(sprintf('File opened: %s', filename));
            else
                warndlg(sprintf('Failed to open: %s', filename));
                return;
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            
            Af=0;
            Ae=0;

            try
                frewind(app.fid);
            catch
            end
            
            try
                i=1;
                while ~feof(app.fid)
                    line = fgetl(app.fid);
                    
                    if contains(line, 'R E A L   E I G E N V A L U E S')
                        disp(line)
                        while ~feof(app.fid)
                            line = fgetl(app.fid);
                            if contains(line, 'NASTRAN')
                                break;
                            else
                                if ~contains(line, 'ORDER')
                                    if contains(line,'0')
                                        data = strsplit(line);
                                        disp(line);
                                        disp([data{1}, '  ', num2str(str2double(data{5}), '%8.2f')]);

                                        try
                                            Af(i)=str2double(data{6});
                                        catch
                                            wandlg('Af failed')
                                            return;
                                        end
                                        try
                                            Ae(i)=str2double(data{4});
                                        catch
                                            wandlg('Ae failed')
                                            return;
                                        end
                                        i=i+1;
                                    end
                                end
                            end
                        end
                        
                    end
                end
            catch
                warndlg('Input array error');
            end 
  
            try
                output_fn=app.NaturalFrequenciesHzEditField.Value;
                output_eig=app.EigenvaluesEditField.Value;
                assignin('base',output_fn,Af');  
                assignin('base',output_eig,Ae');  
            catch
                warndlg('Output array error');
                return;
            end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        try
            frewind(app.fid);
        catch
        end

        ename=app.EigenvectorsEditField.Value;

        i=1;

        j=1;

        while ~feof(app.fid)
            line = fgetl(app.fid);
            if contains(line, 'E I G E N V E C T O R')
                j=1;
                while ~feof(app.fid)
                    line = fgetl(app.fid);
                    if contains(line, 'F O R C E S')
                        msgbox('Arrays written to Matlab Workspace');
                        return;
                    end
                    if contains(line, 'E I G E N V E C T O R')
                        clear AA;
                        
                        j=1;
                        fprintf('\n%s\n',line); 
                    elseif ( (contains(line, 'G') || contains(line, 'T1')) && ~contains(line, 'JOB') && ~contains(line, 'VALUE') && ~contains(line, 'V E C T O R'))
                       disp(line); 
                       line=strrep(line,' TYPE','');
                       line=strrep(line,'   G  ','');
                       AA{j}=line;
                       j=j+1;
                       % data = strsplit(line);
    %                    grid(i) = str2double(data{1});
    %                    disp(['i=', num2str(i), '   grid[', num2str(i), '] = ', num2str(grid(i), '%12.4f')]);
    %                    i = i + 1;
                    end
                    if contains(line, 'JOB')
                        output_name=sprintf('%s_%d',ename,i);
                        assignin('base',output_name,char(AA));
                        i=i+1;
                    end    
                end
                break;
            end

            
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        msgbox('Arrays written to Matlab Workspace');

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mscnastranUIFigure = uifigure('Visible', 'off');
            app.mscnastranUIFigure.Name = 'msc nastran';
            app.msc_nastran_utilityver10byTomIrvineLabel = uilabel(app.mscnastranUIFigure);
            app.msc_nastran_utilityver10byTomIrvineLabel.Text = 'msc_nastran_utility   ver 1.0  by Tom Irvine';
            app.TabGroup = uitabgroup(app.mscnastranUIFigure);
            app.Selectf06ArrayTab = uitab(app.TabGroup);
            app.Selectf06ArrayTab.Title = 'Select f06 Array';
            app.ReadArrayButton = uibutton(app.Selectf06ArrayTab, 'push');
            app.ReadArrayButton.ButtonPushedFcn = createCallbackFcn(app, @ReadArrayButtonPushed, true);
            app.ReadArrayButton.Text = 'Read Array';
            app.EigenvaluesVectorsTab = uitab(app.TabGroup);
            app.EigenvaluesVectorsTab.Title = 'Eigenvalues & Vectors';
            app.ExtractButton = uibutton(app.EigenvaluesVectorsTab, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.NaturalFrequenciesHzEditFieldLabel = uilabel(app.EigenvaluesVectorsTab);
            app.NaturalFrequenciesHzEditFieldLabel.Text = 'Natural Frequencies (Hz)';
            app.NaturalFrequenciesHzEditField = uieditfield(app.EigenvaluesVectorsTab, 'text');
            app.NaturalFrequenciesHzEditField.Value = 'fn';
            app.OutputArrayNamesLabel = uilabel(app.EigenvaluesVectorsTab);
            app.OutputArrayNamesLabel.Text = 'Output Array Names';
            app.EigenvaluesEditFieldLabel = uilabel(app.EigenvaluesVectorsTab);
            app.EigenvaluesEditFieldLabel.Text = 'Eigenvalues';
            app.EigenvaluesEditField = uieditfield(app.EigenvaluesVectorsTab, 'text');
            app.EigenvaluesEditField.Value = 'eigenvalues';
            app.EigenvectorsEditFieldLabel = uilabel(app.EigenvaluesVectorsTab);
            app.EigenvectorsEditFieldLabel.Text = 'Eigenvectors';
            app.EigenvectorsEditField = uieditfield(app.EigenvaluesVectorsTab, 'text');
            app.EigenvectorsEditField.Value = 'mode';
            app.TheoutputeigenvectorsnameswillbeappendedwiththemodenumberLabel = uilabel(app.EigenvaluesVectorsTab);
            app.TheoutputeigenvectorsnameswillbeappendedwiththemodenumberLabel.Text = {'The output eigenvectors names will be '; 'appended with the mode number'};
            app.ReturnButton = uibutton(app.mscnastranUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.mscnastranUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = msc_nastran_utility

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mscnastranUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.mscnastranUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mscnastranUIFigure)
        end
    end
end