classdef multiple_plot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiplePlotUIFigure        matlab.ui.Figure
        UpdateTableButton           matlab.ui.control.Button
        MaxEditField_Y              matlab.ui.control.EditField
        MaxEditField_2Label         matlab.ui.control.Label
        MaxEditField_X              matlab.ui.control.EditField
        MaxEditFieldLabel           matlab.ui.control.Label
        MinEditField_Y              matlab.ui.control.EditField
        MinEditField_2Label         matlab.ui.control.Label
        MinEditField_X              matlab.ui.control.EditField
        MinEditFieldLabel           matlab.ui.control.Label
        GridListBox                 matlab.ui.control.ListBox
        GridListBoxLabel            matlab.ui.control.Label
        DomainLimitsListBox_Y       matlab.ui.control.ListBox
        DomainLimitsListBox_2Label  matlab.ui.control.Label
        AxisTypeListBox_Y           matlab.ui.control.ListBox
        AxisTypeListBox_2Label      matlab.ui.control.Label
        DomainLimitsListBox_X       matlab.ui.control.ListBox
        DomainLimitsLabel           matlab.ui.control.Label
        AxisTypeListBox_X           matlab.ui.control.ListBox
        AxisTypeLabel               matlab.ui.control.Label
        YaxisLabelEditField         matlab.ui.control.EditField
        XaxisLabelEditField_2Label  matlab.ui.control.Label
        XaxisLabelEditField         matlab.ui.control.EditField
        XaxisLabelEditFieldLabel    matlab.ui.control.Label
        TitleEditField              matlab.ui.control.EditField
        TitleEditFieldLabel         matlab.ui.control.Label
        LegendListBox               matlab.ui.control.ListBox
        LegendListBoxLabel          matlab.ui.control.Label
        DoubleclickonArrayNametoaccesspulldownmenuLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        NumberofCurvesListBox       matlab.ui.control.ListBox
        NumberofCurvesLabel         matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        multiple_plot_Appver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        num % Description
        msz %
        kflag %
        nx  %
        ny  %
    end
    
    methods (Access = private)

        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end              
        end
                
        function ChangeRows(app)
            ns=app.NumberofCurvesListBox.Value;
            app.num=str2num(ns); 

            %%%

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list') ) 
                    cell_array{i} ='999';
                else  

                    try
               
                        THM=evalin('base',vars(i).name);
                        sz=size(THM);

                        if(sz(2)==2)
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            try
                ccc=unique(sort(cell_array)); 
                cnames = categorical(ccc);
                sz=size(ccc);
                app.msz=max(sz);
            catch
                app.msz=0;
            end

            app.kflag=0; 

            if(sz(1)==0 || sz(2)==0)
                app.kflag=1;
                warndlg('No arrays available');
                return;
            end             
            
            
            if(app.msz<app.num)
                app.num=app.msz;
                try
                    app.NumberofCurvesListBox.Value=sprintf('%d',app.num);
                    warndlg('Number of curves reset to available number');
                catch
                end
            end 

            w{1}=sprintf(' ');
            for i=2:app.num
                w{end+1}=sprintf(' ');
            end
            w=w';

            % cnames(1);
 

            if(app.num==1)
                cx={cnames(1)};
                pp=true;
            end    
            if(app.num==2)
                cx={cnames(1);cnames(1)};
                pp=[true;true];
            end   
            if(app.num==3)
                cx={cnames(1);cnames(1);cnames(1)};
                pp=[true;true;true];          
            end      
            if(app.num==4)
                cx={cnames(1);cnames(1);cnames(1);cnames(1)};
                pp=[true;true;true;true];         
            end     
            if(app.num==5)
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};
                pp=[true;true;true;true;true];      
            end       
            if(app.num==6)          
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};
                pp=[true;true;true;true;true;true];               
            end            

            %%%

            app.UITable.ColumnWidth={155,155,50};

            try
                app.UITable.ColumnFormat={'char','char','logical'};
            catch
                warndlg('Format failed');
            end

            try
                tdata = table(cx,w,pp,'VariableNames',{'Array Name','Legend','Plot'});
            catch
                try
                    cnames
                    w
                    pp
                catch
                end
                warndlg('tdata failed');
            end

 
            try
                app.UITable.Data=tdata;  
                app.UITable.ColumnEditable=true(1,3);
            catch
            end

        end
        
        function ChangeLimits(app)
            Items = char(app.DomainLimitsListBox_X.Items);
            im=app.DomainLimitsListBox_X.Value;
            [app.nx]=find_index(Items,im);

            if(app.nx==1)
                app.MinEditField_X.Enable='off';
                app.MinEditField_X.Value=' ';
                app.MaxEditField_X.Enable='off';
                app.MaxEditField_X.Value=' ';             
            else
                app.MinEditField_X.Enable='on';
                app.MaxEditField_X.Enable='on';     
            end

            Items = char(app.DomainLimitsListBox_Y.Items);
            im=app.DomainLimitsListBox_Y.Value;
            [app.ny]=find_index(Items,im);

            if(app.ny==1)
                app.MinEditField_Y.Enable='off';
                app.MinEditField_Y.Value=' ';
                app.MaxEditField_Y.Enable='off';
                app.MaxEditField_Y.Value=' ';             
            else
                app.MinEditField_Y.Enable='on';
                app.MaxEditField_Y.Enable='on';     
            end            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeRows(app);
            ChangeLimits(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: NumberofCurvesListBox
        function NumberofCurvesListBoxValueChanged(app, event)
            ChangeRows(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            ClosePlots(app);

            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end   

            Items = char(app.AxisTypeListBox_X.Items);
            im=app.AxisTypeListBox_X.Value;
            [nx_type]=find_index(Items,im);

            Items = char(app.AxisTypeListBox_Y.Items);
            im=app.AxisTypeListBox_Y.Value;
            [ny_type]=find_index(Items,im);           
            
            nx_limits=app.nx;
            ny_limits=app.ny;     

            N=app.num;

            cmap(1,:)=[0 0 0.8];        % dark blue
            cmap(2,:)=[0.8 0 0];        % red
            cmap(3,:)=[0 0 0];          % black
            cmap(4,:)=[0.6 0.3 0];      % brown
            cmap(5,:)=[0 0.5 0.5];      % teal
            cmap(6,:)=[1 0.5 0];        % orange
            cmap(7,:)=[0.5 0.5 0];      % olive
            cmap(8,:)=[0.13 0.55 0.13]; % forest green  
            cmap(9,:)=[0.5 0 0];        % maroon
            cmap(10,:)=[0.5 0.5 0.5 ];  % grey
            cmap(11,:)=[1. 0.4 0.4];    % pink-orange
            cmap(12,:)=[0.5 0.5 1];     % lavender
            cmap(13,:)=[0.05 0.7 1.];   % light blue
            cmap(14,:)=[0  0.8 0.4 ];   % green
            cmap(15,:)=[1 0.84 0];      % gold
            cmap(16,:)=[0 0.8 0.8];     % turquoise
            
            for i=17:N
                cmap(i,:)=[rand() rand() rand()];
            end
            
            A=app.UITable.Data;
            A=table2cell(A);


            k=1;
            
            for i=1:N
                array{i}=char(A{k}); k=k+1;
            end
            for i=1:N
                leg{i}=char(A{k}); k=k+1;
            end
            for i=1:N
                pp{i}=A{k}; k=k+1;
            end

            Items = char(app.GridListBox.Items);
            im=app.GridListBox.Value;
            [ng]=find_index(Items,im);


            Items = char(app.LegendListBox.Items)
            im=app.LegendListBox.Value
            [nlegend]=find_index(Items,im);

            %%%%%

            fig_num=1;

            xmin=1.0e+90;
            xmax=-xmin;
            
            yymin=1.0e+90;
            yymax=-yymin;
            
            iflag=0;
            
            hp=figure(fig_num);
            hold on;
            
            for i=1:N
            
                leg{i}=strrep(leg{i},'_',' ');
                
                if(pp{i}==1)
                    
                    iflag=1;
                    
                    try
                        THM=evalin('base',array{i});
                        
                        if(nlegend==3)
                            plot(THM(:,1),THM(:,2),'Color',cmap(i,:));
                        else  
                            plot(THM(:,1),THM(:,2),'Color',cmap(i,:),'DisplayName',leg{i});
                        end 
                        
                        try
                            if(THM(1,1)<xmin)
                                xmin=THM(1,1);
                            end
                            if(THM(end,1)>xmax)
                                xmax=THM(end,1);
                            end
                        
                            maxy=max(THM(:,2));
                            miny=min(THM(:,2));            
                        
                            if(miny<yymin)
                                yymin=miny;
                            end
                            if(maxy>yymax)
                                yymax=maxy;
                            end
                        catch
                        end
                        
                    catch
                        array{i}
                        try
                            size(THM)
                        catch
                        end
                        out1=sprintf(' plot failed:  %s   ',array{i});
                        warndlg(out1);
                        return;
                        
                    end
                    
                end
            
            end


            if(nlegend<=2)
                legend show;
                if(nlegend==2)
                    legend('location','eastoutside')
                    set(hp, 'Position', [0 0 1400 900]);
                    set(gca,'Fontsize',20);
                end    
            end 

            tstring=app.TitleEditField.Value;
            YS=app.YaxisLabelEditField.Value;
            XS=app.XaxisLabelEditField.Value;

            if(nx_limits==1 ) % automatic
                
            else    % manual
                
                xmin=str2num(app.MinEditField_X.Value);
                xmax=str2num(app.MaxEditField_X.Value);
                        
                xlim([xmin xmax]);    
                
            end 
            
            if(nx_type==2)  % log
                    
                try
                        [xtt,xTT,iflag]=xtick_label(xmin,xmax);
                        
                        if(iflag==1)
                            set(gca,'xtick',xtt);
                            set(gca,'XTickLabel',xTT);
                            fmin=min(xtt);
                            fmax=max(xtt);
                            xlim([fmin,fmax]);                
                        end
                catch    
                end
                    
            end
            
            
            if(ny_limits==2 ) % manual 
            
                ymin=str2num(app.MinEditField_Y.Value);
                ymax=str2num(app.MaxEditField_Y.Value);
                
                ylim([ymin ymax]);
                
            end    
            
            grid off;

            
            if(ng==1)
                
                grid on;
                set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                
                if(nx_type==1 && ny_type==1)
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','lin','YScale','lin');
                end 
                if(nx_type==1 && ny_type==2)
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','lin','YScale','log');
                end
                if(nx_type==2 && ny_type==1)
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','lin');
                end
                if(nx_type==2 && ny_type==2)
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','log');
                end    
                
            end    
            if(ng==2)
                
                grid on;
                set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
             
             
                if(nx_type==1 && ny_type==1)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','lin','YScale','lin');
                end    
                if(nx_type==1 && ny_type==2)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','lin','YScale','log');
                end
                if(nx_type==2 && ny_type==1)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','log','YScale','lin');
                end
                if(nx_type==2 && ny_type==2)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','log','YScale','log');
                end    
                
            end
            if(ng==3)
            
                if(nx_type==1 && ny_type==1)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','lin','YScale','lin');
                end
                if(nx_type==1 && ny_type==2)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','lin','YScale','log');
                end
                if(nx_type==2 && ny_type==1)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','log','YScale','lin');
                end
                if(nx_type==2 && ny_type==2)
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','log','YScale','log');
                end    
                
            end
            
            if(ny_limits==1 && ny_type==2)  % automatic log
                    
                try
                    [ymin,ymax]=ytick_log(yymin,yymax);
                    ylim([ymin,ymax]);
                catch    
                end    
            end    


            title(tstring);
            ylabel(YS);
            xlabel(XS);

            hold off;
             
        end

        % Value changed function: DomainLimitsListBox_X
        function DomainLimitsListBox_XValueChanged(app, event)
            ChangeLimits(app);
        end

        % Value changed function: DomainLimitsListBox_Y
        function DomainLimitsListBox_YValueChanged(app, event)
            ChangeLimits(app);
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            ChangeRows(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiplePlotUIFigure = uifigure('Visible', 'off');
            app.MultiplePlotUIFigure.Name = 'Multiple Plot';
            app.ReturnButton = uibutton(app.MultiplePlotUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.multiple_plot_Appver10byTomIrvineLabel = uilabel(app.MultiplePlotUIFigure);
            app.multiple_plot_Appver10byTomIrvineLabel.Text = 'multiple_plot_App    ver 1.0   by Tom Irvine         Each array must have two columns.';
            app.PlotButton = uibutton(app.MultiplePlotUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NumberofCurvesLabel = uilabel(app.MultiplePlotUIFigure);
            app.NumberofCurvesLabel.Text = 'Number of  Curves';
            app.NumberofCurvesListBox = uilistbox(app.MultiplePlotUIFigure);
            app.NumberofCurvesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofCurvesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCurvesListBoxValueChanged, true);
            app.NumberofCurvesListBox.Value = '2';
            app.UITable = uitable(app.MultiplePlotUIFigure);
            app.UITable.ColumnName = {'Array Name'; 'Legend'; 'Plot'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel = uilabel(app.MultiplePlotUIFigure);
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel.Text = 'Double click on Array Name to access pull down menu.';
            app.LegendListBoxLabel = uilabel(app.MultiplePlotUIFigure);
            app.LegendListBoxLabel.Text = 'Legend';
            app.LegendListBox = uilistbox(app.MultiplePlotUIFigure);
            app.LegendListBox.Items = {'On, Inside', 'On, Outside', 'Off'};
            app.LegendListBox.Value = 'On, Inside';
            app.TitleEditFieldLabel = uilabel(app.MultiplePlotUIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.XaxisLabelEditFieldLabel = uilabel(app.MultiplePlotUIFigure);
            app.XaxisLabelEditFieldLabel.Text = 'X-axis Label';
            app.XaxisLabelEditField = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.XaxisLabelEditField_2Label = uilabel(app.MultiplePlotUIFigure);
            app.XaxisLabelEditField_2Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.AxisTypeLabel = uilabel(app.MultiplePlotUIFigure);
            app.AxisTypeLabel.Text = 'Axis Type';
            app.AxisTypeListBox_X = uilistbox(app.MultiplePlotUIFigure);
            app.AxisTypeListBox_X.Items = {'linear', 'log'};
            app.AxisTypeListBox_X.Value = 'linear';
            app.DomainLimitsLabel = uilabel(app.MultiplePlotUIFigure);
            app.DomainLimitsLabel.Text = 'Domain Limits';
            app.DomainLimitsListBox_X = uilistbox(app.MultiplePlotUIFigure);
            app.DomainLimitsListBox_X.Items = {'automatic', 'manual'};
            app.DomainLimitsListBox_X.ValueChangedFcn = createCallbackFcn(app, @DomainLimitsListBox_XValueChanged, true);
            app.DomainLimitsListBox_X.Value = 'automatic';
            app.AxisTypeListBox_2Label = uilabel(app.MultiplePlotUIFigure);
            app.AxisTypeListBox_2Label.Text = 'Axis Type';
            app.AxisTypeListBox_Y = uilistbox(app.MultiplePlotUIFigure);
            app.AxisTypeListBox_Y.Items = {'linear', 'log'};
            app.AxisTypeListBox_Y.Value = 'linear';
            app.DomainLimitsListBox_2Label = uilabel(app.MultiplePlotUIFigure);
            app.DomainLimitsListBox_2Label.Text = 'Domain Limits';
            app.DomainLimitsListBox_Y = uilistbox(app.MultiplePlotUIFigure);
            app.DomainLimitsListBox_Y.Items = {'automatic', 'manual'};
            app.DomainLimitsListBox_Y.ValueChangedFcn = createCallbackFcn(app, @DomainLimitsListBox_YValueChanged, true);
            app.DomainLimitsListBox_Y.Value = 'automatic';
            app.GridListBoxLabel = uilabel(app.MultiplePlotUIFigure);
            app.GridListBoxLabel.Text = 'Grid';
            app.GridListBox = uilistbox(app.MultiplePlotUIFigure);
            app.GridListBox.Items = {'Major & Minor On', 'Major On, Minor Off', 'Off'};
            app.GridListBox.Value = 'Major & Minor On';
            app.MinEditFieldLabel = uilabel(app.MultiplePlotUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField_X = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.MinEditField_2Label = uilabel(app.MultiplePlotUIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_Y = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.MaxEditFieldLabel = uilabel(app.MultiplePlotUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField_X = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.MaxEditField_2Label = uilabel(app.MultiplePlotUIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_Y = uieditfield(app.MultiplePlotUIFigure, 'text');
            app.UpdateTableButton = uibutton(app.MultiplePlotUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            % Show the figure after all components are created
            app.MultiplePlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiple_plot_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MultiplePlotUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MultiplePlotUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiplePlotUIFigure)
        end
    end
end