classdef multispan_beam_bending_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultispanBeamFEAUIFigure        matlab.ui.Figure
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryBCsTab                  matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        DistancefromLeftEndLabel        matlab.ui.control.Label
        UITable_msweep                  matlab.ui.control.Table
        NumberListBox                   matlab.ui.control.ListBox
        NumberListBoxLabel              matlab.ui.control.Label
        IntermediatePinnedLocationsLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.EditField
        NumberofElementsLabel           matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_1                     matlab.ui.control.EditField
        EditField_1Label                matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        ImageIBeam                      matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        MaterialAddedMassTab            matlab.ui.container.Tab
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        UniformNonstructuralMasslbmEditField  matlab.ui.control.EditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        NaturalFrequenciesTab           matlab.ui.container.Tab
        UITable_fn                      matlab.ui.control.Table
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        CalculateNaturalFrequenciesButton  matlab.ui.control.Button
        PlotModeShapeButton             matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        BaseExcitationResponseTab       matlab.ui.container.Tab
        inchLabel                       matlab.ui.control.Label
        ScriptwillfindnearestnodelocationLabel  matlab.ui.control.Label
        PlotListBox                     matlab.ui.control.ListBox
        PlotListBoxLabel                matlab.ui.control.Label
        ResponseLocationfromLeftEndEditField  matlab.ui.control.EditField
        ResponseLocationfromLeftEndLabel  matlab.ui.control.Label
        NumberofModestoIncludeListBox   matlab.ui.control.ListBox
        NumberofModestoIncludeLabel     matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        PartitionMatricesTab            matlab.ui.container.Tab
        PartitionMatricesButton         matlab.ui.control.Button
        TransmissibilityTab             matlab.ui.container.Tab
        OutputArrayPrefixEditField      matlab.ui.control.EditField
        OutputArrayPrefixEditFieldLabel  matlab.ui.control.Label
        EndFreqHzEditField              matlab.ui.control.EditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.EditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateBaseInputTrans         matlab.ui.control.Button
        PSDInputTab                     matlab.ui.container.Tab
        OutputPSDArrayPrefixEditField   matlab.ui.control.EditField
        OutputPSDArrayPrefixEditFieldLabel  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculateButton                 matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        StiffnessArrayEditField         matlab.ui.control.EditField
        StiffnessArrayEditFieldLabel    matlab.ui.control.Label
        MassArrayEditField              matlab.ui.control.EditField
        MassArrayEditFieldLabel         matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        multispan_beam_bending_FEAver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        BC                 % Boundary condition
        C                  % Modal constant
        E                  % Elastic modulus
        FullModeShapes     %
        HM_accel           %
        HM_rd              %
        HM_stress          %       
        HM_stress_response %
        Kwd                %
        Kww                %
        L                  % Length
        LBC                % Left boundary condition
        ModeShapes         % Mode Shapes
        MOI                % Area moment of intertia
        MST                % Mode Shapes Transpose
        Mwd                %   
        Mww                %
        RBC                % Right boundary condition
        T1                 %
        T2                 %
        THM                %
        TT                 %
        TT1                %
        TT2                %
        TZ_tracking_array  %
        ZZ                 % Mode shape

        absolute_accel   % 
        acc              %
        acc_trans        %
        acceleration     %
        adisp            %
        atrans           %
        bending_moment   % 
        bending_stress   % 
        beta             % eigenvalue parameter
        cdof             %
        chplot           % 
        cna              % Distance to neutral axis
        constraint_matrix %
        ddd              %
        ddisp            %
        dm               % Damping
        dof              % degrees-of-freedom
        dof_status       % dof status
        dt               %
        drot_dof         %
        dx               % element length array
        ea               %
        eac              %
        fmax             %
        fig_num          %
        fn               % Natural frequencies
        free_dof_array   %
        freq             %
        fmin           %
        global_mass      %
        global_mass_unc  %  
        global_stiff     %
        global_stiff_unc %
        iflag            %
        imat             %
        ipart            %
        iu               % iu=1 for English units
        kflag            %
        mass             %
        mass_unc         %
        method           %
        mode             %
        na               %
        nde              %
        ne               % number of elements
        nem              %
        nem_ea           %
        nff              %
        ngw              %
        nnn              %
        nnn_max          %
        n_msweep         %
        node_near        %
        np               %
        nt               %
        num              %
        num_nodes_Tz_free %
        num_columns      %
        number_modes     %

        omega            %
        omegad           %
        omegan           % Angular natural frequencies
        part             % participation factor
        part_ModeShapes  %
        part_MST         %
        part_omega       %
        part_fn          %
        pomn2            %

        ppp_a            %
        ppp_pv           % 
        ppp_rd           %
        ppp_st           %

        pv_trans         %

        qqq_a            %
        qqq_pv           % 
        qqq_rd           %
        qqq_st           %

        rdtrans          %
        rd_trans         %
        relative_disp    %
        relative_vel     % 
        response_dof     %
        rho              % Mass density
        rot_dof          %
        rot_constraint_matrix %
        rs               %
        s1               % modal frequency 1
        s2               % modal frequency 2
        s3               % modal frequency 3
        s4               % modal frequency 4
        s5               % modal frequency 5
        s6               % modal frequency 6          
        s7               % modal frequency 7
        s8               % modal frequency 8
        s9               % modal frequency 9
        s10              % modal frequency 10
        s11              % modal frequency 11
        s12              % modal frequency 12  

        shear_force      % 
        sq_mass          % sqrt of mass
        stiff            %
        stiff_unc        %
        strans           % 
        stress           %
        stress_trans     %
        tdof             % translational dof
        titlea           %
        tracking_array   %
        x                % x length along beam for mode shap        
        xx               % x length along beam for mode shapes
        xxr              % 
        y                % Mode shape

        accel_psd        %
        pv_psd           %
        rd_psd           %
        stress_psd       % 
        itype            %
        scale            %
        LU               %
                
    end
    
    methods (Access = private)

       function update_psd(app)
            CloseFigures(app);
            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density_alt();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end       

        function ChangeUnits(app)
            app.ipart=0;

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.LengthinEditFieldLabel.Text='Length (in)';
                app.inchLabel.Text='inch';
                app.DistancefromLeftEndLabel.Text='Distance from Left End (in)';
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            else
                app.LengthinEditFieldLabel.Text='Length (m)'; 
                app.inchLabel.Text='m';   
                app.DistancefromLeftEndLabel.Text='Distance from Left End (m)'; 
                app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (m/sec)'; 'Wavenumber (rad/m)'};
            end    

            if(app.iu==1)
                app.scale=386;
                app.LU='in';
            else
                app.scale=9.81;
                app.LU='m';
            end    

            
            Clearfn(app);
            ChangeCrossSection(app);  
            ChangeMaterials(app);
        end    

        function ChoosePlots(app)
            Items = strtrim(app.PlotListBox.Items);
            im = strtrim(app.PlotListBox.Value);
            app.chplot = find(strcmp(Items, im));

            app.ResponseLocationfromLeftEndEditField.Visible='off';
            app.ResponseLocationfromLeftEndLabel.Visible='off';
            app.ScriptwillfindnearestnodelocationLabel.Visible='off';
            app.inchLabel.Visible='off';

            if(app.chplot>=2)
                app.ResponseLocationfromLeftEndEditField.Visible='on';
                app.ResponseLocationfromLeftEndLabel.Visible='on';
                app.ScriptwillfindnearestnodelocationLabel.Visible='on';  
                app.inchLabel.Visible='on';
            end    
            app.ipart=0;
        end    

        function Clearfn(app)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';            
            app.UITable_fn.Visible='off';
            app.PlotModeShapeButton.Visible='off';    
            app.fn=0;
            app.ipart=0;
        end   
        
        function CloseFigures(app)

            Clearfn(app);

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6';'Mode 7';'Mode 8'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            
        end        

        function msweep(app)

            Clearfn(app);

            app.n_msweep = str2num(app.NumberListBox.Value);  

            Nrows=app.n_msweep;
            Ncolumns=2;
             
            A=app.UITable_msweep.Data;
             

            if(Nrows==0)
                app.UITable_msweep.Visible='off';
                app.DistancefromLeftEndLabel.Visible='off';
                return;
            else
                app.UITable_msweep.Visible='on';   
                app.DistancefromLeftEndLabel.Visible='on';               
            end


            sz=size(A);
            Arows=sz(1);
             
            data_s = cell(Nrows, Ncolumns);
            data_s(:, 1) = num2cell(1:Nrows);
             
            if(~isempty(A))
                
                M=min([ Arows Nrows ]);
                
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end    
                end   
             
            end
             
            app.UITable_msweep.Data=data_s;        
        end

        function ChangeCrossSection(app)
            Clearfn(app);

            n=app.CrossSectionListBox.Value;
            
            app.EditField_3.Visible='off';
            app.EditField_4.Visible='off';
            app.EditField_3Label.Visible='off';
            app.EditField_4Label.Visible='off'; 
            app.ImageIBeam.Visible='off';
            
            if(contains(n,'Rectangular'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Width (in)';
                    app.EditField_2Label.Text='Thickness (in)';  
                else
                    app.EditField_1Label.Text='Width (m)';
                    app.EditField_2Label.Text='Thickness (m)';  
                end    
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';               
            end
            if(contains(n,'Pipe'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Outer Diameter (in)';
                    app.EditField_2Label.Text='Wall Thickness (in)';
                else
                    app.EditField_1Label.Text='Outer Diameter (m)';
                    app.EditField_2Label.Text='Wall Thickness (m)';
                end
                app.EditField_2.Visible='on';
                app.EditField_2Label.Visible='on';                 
            end
            if(contains(n,'Cylinder'))
                 if(app.iu==1)
                    app.EditField_1Label.Text='Diameter (in)';
                 else
                    app.EditField_1Label.Text='Diameter (m)';                     
                 end
                 app.EditField_2.Visible='off';     
                 app.EditField_2Label.Visible='off';                      
            end
            if(contains(n,'I-Beam'))
                if(app.iu==1)
                    app.EditField_1Label.Text='Base Width (in)';
                    app.EditField_2Label.Text='Total Height (in)';
                    app.EditField_3Label.Text='Flange Thickness (in)';     
                    app.EditField_4Label.Text='Web Thickness (in)';
                else
                    app.EditField_1Label.Text='Base Width (m)';
                    app.EditField_2Label.Text='Total Height (m)';
                    app.EditField_3Label.Text='Flange Thickness (m)';     
                    app.EditField_4Label.Text='Web Thickness (m)';
                end

                app.EditField_2.Visible='on';               
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_2Label.Visible='on';                    
                app.EditField_3Label.Visible='on';
                app.EditField_4Label.Visible='on';  
                app.ImageIBeam.Visible='on';
            end
            if(contains(n,'Other'))
                
                if(app.iu==1)
                    app.EditField_1Label.Text='Area (in^2)';
                    app.EditField_2Label.Text='Area moment Inertia (in^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (in)';
                else
                    app.EditField_1Label.Text='Area (m^2)';
                    app.EditField_2Label.Text='Area moment Inertia (m^4)';
                    app.EditField_3Label.Text='Distance from Neutral Axis (m)';
                end

                app.EditField_2.Visible='on';  
                app.EditField_2Label.Visible='on';                 
                app.EditField_3.Visible='on';  
                app.EditField_3Label.Visible='on';                
            end
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
        end
        
        function ChangeMaterials(app)
            
            Clearfn(app);
            
            app.E=0;
            app.rho=0;            

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';   
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';
            end    

            app.ElasticModuluspsiEditField.Value=sprintf('%8.4g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%8.4g',app.rho);        

        end

      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function pre_trans(app)   

            if(isempty(app.omegan) || length(app.omegan)<=1)
                warndlg('Calculate Natural Frequencies first');
                return;
            end    

            if(isempty(app.dm))
                warndlg('Save damping first');
                return;
            end            

            app.fig_num=1;

            app.num_nodes_Tz_free=round(app.dof/2);
            
            % app.dm

            num_Tz=length(app.TZ_tracking_array);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%  need to cleanup
            
            sz=size(app.Mww);
            app.nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.omegan=2*pi*app.fn;

            omn2=app.omegan.^2;

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            oct = 2^(1/48);
            f_min = app.fmin;
            f_max = app.fmax;
            
            k = 0:floor(log(f_max/f_min) / log(oct));
            f = f_min * oct.^k;
            f(f > f_max) = f_max;
            
            app.freq=fix_size(f);
            app.np=length(f);
            
            %
             
            app.omega=2*pi*f;
            om2=app.omega.^2;
            
            %
            [~,jnear]=min(abs(app.omegan(1)-app.omega));
            
            N=zeros(app.nff,1);
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            num_modes_include=str2num(app.NumberofModestoIncludeListBox.Value);
            
            iaa=1;
            ibb=num_modes_include;
                  
            if(app.nff> num_modes_include)
                app.nff=num_modes_include;
            end    
            
            %
            app.y=ones(app.nem,1);
            
            try
                A=-app.MST*app.Mwd*app.y;
            catch
                app.nem
                size(app.MST)
                size(app.Mwd)
                size(app.y)
                warndlg('Error:  ref 1')
                return;
            end
            %
            app.acc_trans=zeros(app.np,num_Tz);
            app.pv_trans=zeros(app.np,num_Tz);
            app.rd_trans=zeros(app.np,num_Tz);
            app.stress_trans=zeros(app.np,num_Tz);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             damp=app.dm;
            
            fprintf('\n\n  app.np=%d  iaa=%d  ibb=%d \n\n',app.np,iaa,ibb);

            for k=1:app.np  % for each excitation frequency
                
                for i=iaa:ibb  % dof

                    N(i)=A(i)/(omn2(i)-om2(k) + (1i)*2*damp(i)*app.omegan(i)*app.omega(k));
                end
                
                Ud=zeros(app.nem,1); 
                for i=1:app.nem  % convert acceleration input to displacement
                    Ud(i)=1/(-om2(k));
                end
            %
                Uw=app.ModeShapes*N;   
            
                Udw=[Ud; Uw];
            %
                U=app.TT*Udw;    
                
                nu=length(U);
                
                if(k==1)
            %        fprintf(' nu=%d num_elem=%d  num_nodes_Tz_free=%d\n',nu,num_elem,num_nodes_Tz_free);
            %        ngw
                end
                
                Ur=zeros(nu,1);
                
                Ur(app.ngw(1:nu)) = U(1:nu);

                num_elem=app.ne;
                
                if(k==1)
                    num_nodes=num_elem+1;
            %       total_dof=num_nodes*2;
                
                    R_tracking_array=zeros(num_nodes,1);
            
            %        rot_dof;  
                    num_Ur=length(Ur);
                
                    app.rot_constraint_matrix=app.constraint_matrix;
                
            %        constraint_matrix;
                
                    length(app.constraint_matrix(:,1))
                
                    for i=length(app.constraint_matrix(:,1)):-1:1
                        if(app.constraint_matrix(i,3)==0)
                            app.rot_constraint_matrix(i,:)=[];
                        end
                    end
                
                    bbb=(1:num_Ur);
                
                    for i=1:num_Tz
                        [Lia,Locb] =ismember(app.TZ_tracking_array,bbb);
                        if(Lia==1)
                            bbb(Locb)=[];
                        end
                    end
                
            %%        fprintf(' num_nodes=%d  num_Ur=%d \n',num_nodes,num_Ur);
                    ccc=app.rot_constraint_matrix(:,1);
                    
                    kv=1;
                    for jj=1:num_nodes    
                        if(ismember(jj,ccc))
            %                fprintf(' jj=%d  included \n',jj);
                        else    
            %                fprintf(' jj=%d  kv=%d \n',jj,kv);
                            R_tracking_array(jj)=bbb(kv);
                            kv=kv+1;
                        end
                    end
                end

                for jk=1:num_Tz
                    node=jk;    
                    ij=app.TZ_tracking_array(node);
                    app.acc_trans(k,jk)=om2(k)*abs(Ur(ij));
                    app.rd_trans(k,jk)=abs(Ur(ij)-Ud(1));
                end
                
                for nk=1:num_nodes-1
                
                    left_node=nk;
                    right_node=nk+1;
            
                    LL=abs(app.xx(left_node)-app.xx(right_node));
                    
                    [B]=beam_stress_B(0,LL);
                    
                    TL=app.TZ_tracking_array(left_node);
                    TR=app.TZ_tracking_array(right_node);
                    
                    URL=0;
                    URR=0;
                    
                    if(R_tracking_array(left_node)~=0)
                        RL=R_tracking_array(left_node);
                        URL=Ur(RL);
                    end
                    if(R_tracking_array(right_node)~=0)
                        RR=R_tracking_array(right_node);
                        URR=Ur(RR);            
                    end
                     
                     d=transpose([Ur(TL) URL Ur(TR) URR]);
            
            %       if(k==1)
            %           fprintf('nk=%d  %d %d %d %d   \n',nk,TL,RL,TR,RR);
            %            fprintf('  %8.4g  %8.4g  %8.4g  %8.4g \n\n',d(1),d(2),d(3),d(4));
            %            B
            %        end
            
                        app.stress_trans(k,nk)=abs(app.cna*app.E*B*d); 
                        
                       QQQ=1; 
                       if(k==jnear  && QQQ==1)
            %                 dzL=Ur(TL);
            %                 ryL=URL; 
            %                 dzR=Ur(TR); 
            %                 ryR=URR; 
                             app.s1=app.stress_trans(k,nk);
            %                 fprintf('\n\n nk=%d \n\n',nk);
            %                 fprintf(' cna=%g  E=%7.3g  s1=%8.4g \n',cna,E,s1*386);
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(1)*dzL),imag(B(1)*dzL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(2)*ryL),imag(B(2)*ryL));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(3)*dzR),imag(B(3)*dzR));
            %                 fprintf('B  %7.3g  %7.3g\n',real(B(4)*ryR),imag(B(4)*ryR));   
            %                 cna*E*B*d
            %                transpose([Ur(TL) URL Ur(TR) URR]);
                        end
                    
                end
                
            % stress at right end  
                app.stress_trans(k,end)=app.stress_trans(k,end-1);
                
            end

            app.pv_trans = app.rd_trans .* (2 * pi * app.freq);

            md=5;
            x_label='Frequency (Hz)';

            %%%%%%%%%%%%%%%%

            [~,column_a,max_a]=find_max_2d_array(app.acc_trans);
            app.ppp_a=[app.freq app.acc_trans(:,column_a)];

            app.pv_trans=app.scale*app.pv_trans;
            [~,column_pv,max_pv]=find_max_2d_array(app.pv_trans);
            app.ppp_pv=[app.freq app.pv_trans(:,column_pv)];

            app.rd_trans=app.scale*app.rd_trans;
            [~,column_rd,max_rd]=find_max_2d_array(app.rd_trans);
            app.ppp_rd=[app.freq app.rd_trans(:,column_rd)];

            app.stress_trans=app.scale*app.stress_trans;
            [~,column_st,max_st]=find_max_2d_array(app.stress_trans);
            app.ppp_st=[app.freq app.stress_trans(:,column_st)];

            %%%%%%%%%%%%%%%%%

            fprintf('\n Max Values \n');

            fprintf('          Acceleration Trans = %8.4g G/G \n',max_a);

            if(app.iu==1)
                fprintf('       Pseudo Velocity Trans = %8.4g ips/G \n',max_pv);
                fprintf(' Relative Displacement Trans = %8.4g in/G \n',max_rd);
                fprintf('                Stress Trans = %8.4g psi/G \n',max_st);  
            else
                fprintf('       Pseudo Velocity Trans = %8.4g m/sec/G \n',max_pv);
                fprintf(' Relative Displacement Trans = %8.4g m/G \n',max_rd);
                fprintf('                Stress Trans = %8.4g Pa/G \n',max_st); 
            end

            K=max_st/(max_pv*sqrt(app.E*app.rho));  

            if(app.itype==1)
                fprintf('\n Stress-Velocity K = %8.4g \n',K);      
            end

            if(app.chplot>=2 && app.itype==1)

                xr=str2num(app.ResponseLocationfromLeftEndEditField.Value);

                [~,app.node_near]=min(abs(app.xx-xr));

                %%%%%%%%%%%%%%%%%
    
                y_label='Trans (G/G)';
                t_string=sprintf('Acceleration Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_a=[app.freq app.acc_trans(:,app.node_near)];

                if(min(app.qqq_a(:,2))>0)   
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_a,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (ips/G)';
                else
                    y_label='Trans (m/sec/G)';                    
                end    

                t_string=sprintf('Pseudo Velocity Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_pv=[app.freq app.pv_trans(:,app.node_near)];

                if(min(app.qqq_pv(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_pv,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (in/G)';
                else
                    y_label='Trans (m/G)';                    
                end   

                t_string=sprintf('Rel Disp Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_rd=[app.freq app.rd_trans(:,app.node_near)];

                if(app.qqq_rd(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_rd,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (psi/G)';
                else
                    y_label='Trans (Pa/G)';                    
                end   

                t_string=sprintf('Stress Transmissibility at x=%7.3g %s',app.xx(app.node_near),app.LU);
    
                app.qqq_st=[app.freq app.stress_trans(:,app.node_near)];

                if(app.qqq_st(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.qqq_st,app.fmin,app.fmax,md);            
                end

            end    

            if(app.chplot~=2 && app.itype==1)
            
                %%%%%%%%%%%%%%%%%
    
                y_label='Trans (G/G)';
                t_string=sprintf('Maximum Acceleration Transmissibility at x=%7.3g %s',app.xx(column_a),app.LU);
    
                if(min(app.ppp_a(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_a,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (ips/G)';
                else
                    y_label='Trans (m/sec/G)';                    
                end   

                t_string=sprintf('Maximum Pseudo Velocity Transmissibility at x=%7.3g %s',app.xx(column_pv),app.LU);
    
                if(min(app.ppp_pv(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_pv,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
    
                if(app.iu==1)
                    y_label='Trans (in/G)';
                else
                    y_label='Trans (m/G)';                    
                end   


                t_string=sprintf('Max Rel Disp Transmissibility at x=%7.3g %s',app.xx(column_rd),app.LU);
    
                if(app.ppp_rd(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_rd,app.fmin,app.fmax,md); 
                end
    
                %%%%%%%%%%%%%%%%%
                
                if(app.iu==1)
                    y_label='Trans (psi/G)';
                else
                    y_label='Trans (Pa/G)';                    
                end   


                t_string=sprintf('Max Stress Transmissibility at x=%7.3g %s',app.xx(column_st),app.LU);
    
                if(app.ppp_st(:,2)>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,app.ppp_st,app.fmin,app.fmax,md);            
                end

            end

            %%%%%%%%%%%%%%%%%            

            assignin('base', 'max_accel_trans', app.ppp_a);
            assignin('base', 'max_pv_trans', app.ppp_pv);
            assignin('base', 'max_rd_trans', app.ppp_rd);
            assignin('base', 'max_stress_trans', app.ppp_st);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.fig_num=1;

            ChangeUnits(app);
            CloseFigures(app);
            ChangeCrossSection(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            msweep(app);
            ChoosePlots(app);
            update_psd(app);

            app.UITable_msweep.ColumnWidth={40,73};

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style

        end

        % Button pushed function: CalculateNaturalFrequenciesButton
        function CalculateNaturalFrequenciesButtonPushed(app, event)
            CloseFigures(app);
        
            fprintf('\n * * * * * * * * * \n\n');  

            app.L=str2num(app.LengthinEditField.Value);
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);
       
            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
            end
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=3;
            end
            if(contains(nL,'Pinned'))
                app.LBC=2;
            end   
            if(contains(nL,'Free'))
                app.LBC=1;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=3;                
            end
            if(contains(nR,'Pinned'))
                app.RBC=2;                
            end   
            if(contains(nR,'Free'))
                app.RBC=1;                
            end    
            
    
            %%%%%%%%%%%%%%%%%%%%%

            app.L
            app.E
            app.rho
            
            n=app.CrossSectionListBox.Value;
            
            if(contains(n,'Rectangular'))
                width=str2num(app.EditField_1.Value);
                thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=beam_rectangular_geometry(width,thick);            
            end
            if(contains(n,'Pipe'))
                diameter=str2num(app.EditField_1.Value);
                wall_thick=str2num(app.EditField_2.Value);
                [area,app.MOI,app.cna]=pipe_geometry_wall(diameter,wall_thick);                
            end
            if(contains(n,'Cylinder'))
                diameter=str2num(app.EditField_1.Value);
                [area,app.MOI,app.cna]=cylinder_geometry(diameter); 

                diameter
                area
                app.MOI
                app.cna

            end
            if(contains(n,'I-Beam'))
                B=str2num(app.EditField_1.Value);
                H=str2num(app.EditField_2.Value);
                Tf=str2num(app.EditField_3.Value);     
                Tw=str2num(app.EditField_4.Value);  
                [area,app.MOIz,app.MOIy]=Ibeam_geometry(B,H,Tf,Tw);
                app.MOI=app.MOIz;
                app.cna=H/2;                
            end
            if(contains(n,'Other'))
                area=str2num(app.EditField_1.Value);
                app.MOI=str2num(app.EditField_2.Value);
                app.cna=str2num(app.EditField_3.Value);       
            end
            
            nsm=str2num(app.UniformNonstructuralMasslbmEditField.Value);

            if(app.iu==1)
                nsm=nsm/386;
            end
            
            rho_mpL=app.rho*area + nsm/app.L;   % mass per unit length
            tmass=rho_mpL*app.L;
            app.sq_mass=sqrt(tmass);

            fprintf('\n area=%8.4g in^2  \t mass=%8.4g lbf sec^2/in \t MOI=%8.4g in^4 \n\n',area,tmass,app.MOI);
         
            %%%%%%%%%%%%%%%%%%%%%

            disp('ref 8')

            app.ne=str2num(app.NumberofElementsEditField.Value)

            try
            
                app.ne=str2num(app.NumberofElementsEditField.Value);
    
                if(app.ne==0 || isempty(app.ne))
                    disp('ref 1')
                    app.ne

                    warndlg(' Number of elements must be >=1 '); 
                    return; 
                end

            catch
                disp('ref 2')
                app.ne
                warndlg('Enter the number of elements');
                return;
            end

            deltax=app.L/app.ne;
    
            app.xx=zeros((app.ne+1),1);

            for i=2:(app.ne+1)
                app.xx(i)=app.xx(i-1)+deltax; 
            end 

            numi=str2num(app.NumberListBox.Value);
            N=numi;


            if(N==0)

                int_index=0;

            else     

                int_index=zeros(N,1);            

                datad=app.UITable_msweep.Data;

                A=string(datad);
     
                int_loc=zeros(N,1);
                
                try
                    k=1;
    
                    for i=1:N
                        k=k+1;
                    end    

                    for i=1:N
         
                        a=str2num(A(k)); k=k+1;

                        int_loc(i,1) = a;
        
                        if(int_loc(i)>=app.L)
                            warndlg('Intermediate location >= length ');
                            return;
                        end

                        [~,index] = min(abs(app.xx-int_loc(i)));
        
                        app.xx(index)=int_loc(i);
        
                        int_index(i)=index;

                    end  

                    int_index=sort(int_index);
                    
                catch
                    warndlg('Input Arrays read failed');
                    return;
                end

            end
            
            app.dx=zeros(app.ne,1);
            for i=1:app.ne
                app.dx(i)=app.xx(i+1)-app.xx(i);
                
                if(app.dx(i)<1.0e-12)
                    warndlg('Coincident nodes. Increase number of elements');
                    return;
                end
                
            end
            
            disp(' ');
            disp(' Node locations');
            for i=1:length(app.xx)
                fprintf(' %8.4g \n',app.xx(i));
            end
            disp(' ');

            app.dof=2*app.ne+2;

            I=app.MOI;

            [klocal] = local_stiffness(app.E,I,app.ne,app.dx);
            [mlocal] = local_mass(rho_mpL,app.ne,app.dx);
            
            kk=zeros(4,4);
            mm=zeros(4,4);
            
            for i=1:4
                for j=1:4
                    kk(i,j)=klocal(1,i,j);
                    mm(i,j)=mlocal(1,i,j);
                end
            end  

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.stiff,app.mass,app.stiff_unc,app.mass_unc,app.dof,app.dof_status,ivector]=...
                        mspan_beam_assembly_unc(app.ne,klocal,mlocal,app.LBC,app.RBC,app.dof,numi,int_index);           
            
            %% disp('dof_status')
            %% for i=1:length(dof_status)
            %%    fprintf(' %g \n',dof_status(i));
            %% end
            %% disp(' ');
            
            %% disp('ivector');
            %% for i=1:length(ivector)
            %%    fprintf(' %g \n',ivector(i));
            %% end
            %% disp(' ');

            if(app.ne==1)
                app.stiff
                app.mass
            end
           
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            total_mass=tmass;
        
            if(app.iu==1) % English    
                out1=sprintf('\n Total mass = %8.4g lbm ',tmass*386);
            else
                out1=sprintf('\n Total mass = %8.4g kg ',tmass);        
            end
            
            disp(out1);   

            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiff,app.mass,2);

            [app.part]=beam_fn_pf_emm(app.iu,total_mass,app.dof,app.fn,app.mass,app.ModeShapes,app.MST,app.dof_status,ivector);  % function

            app.fn=fix_size(app.fn);
     
            n=length(app.fn);

            if(n>12)
                n=12;
            end    

            emm=zeros(n,1);
            cp=zeros(n,1);

            rho_mpa=(rho_mpL*app.L)/area;

            EI_term = sqrt(app.E*app.MOI/rho_mpa);

            for i=1:n
                app.beta(i)=sqrt(app.omegan(i)/EI_term);
                cp(i)=app.beta(i)*EI_term;
                kw=app.omegan(i)/cp(i);
                emm(i)=app.part(i)^2/tmass;
    
                data_s{i,1}=sprintf('%d',i);
                data_s{i,2}=sprintf('%8.4g',app.fn(i));
                data_s{i,3}=sprintf('%7.3g',app.part(i));
                data_s{i,4}=sprintf('%7.3f',emm(i));
                data_s{i,5}=sprintf('%7.4g',cp(i));
                data_s{i,6}=sprintf('%7.3g',kw);
            end

            app.UITable_fn.Data=data_s;
            app.UITable_fn.Visible='on';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_fn,s) % adjust the style

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         
            app.PlotModeShapeButton.Enable='on';
            app.PlotModeShapeButton.Visible='on';
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';

        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            Clearfn(app);
            ChangeMaterials(app);
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            Clearfn(app);
            ChangeCrossSection(app);
        end

        % Value changed function: NumberListBox
        function NumberListBoxValueChanged(app, event)
           Clearfn(app); 
           msweep(app); 
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
               
            n=12;

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else

                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;           
                    for i=1:length(vd)
                        v=vd{i};
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            nnum=str2num(app.SelectModeforPlotListBox.Value);
              
            LF=length(app.fn);
            
            if(nnum>LF)
                out1=sprintf(' Highest mode number = %d',LF);
                warndlg(out1);
                return;
            end
               
            orig_dof=length(app.dof_status);
            dof_half=round(orig_dof/2);
            %
            choice=1;
            %
            for j=nnum:nnum
            %
                if(choice==1)
            %      
                  at = sprintf(' Mode %d   freq=%8.4g Hz',j,app.fn(j));
                  pf_mode=app.ModeShapes(:,j);  
            %
                    for i=1:orig_dof
                        if(app.dof_status(i)==0)
            %       
                            pf_mode=[pf_mode(1:(i-1));0;pf_mode(i:length(pf_mode))];
            %
                        end
                    end
            %
                    pmode=zeros(dof_half,1);
            %        
                    for i=1:dof_half
                        pmode(i)=pf_mode(2*i-1);
                    end
            %
                    figure(app.fig_num);
                    app.fig_num=app.fig_num+1;
            %
                    plot(app.xx,pmode); 
                    grid;
                    set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');    
                    title(at);
                    if(app.iu==1)
                        xlabel('x (inch)'); 
                    else
                        xlabel('x (meters)');
                    end    
                    ylabel('modal displacement');
            %
                end
            end

        end

        % Button pushed function: PartitionMatricesButton
        function PartitionMatricesButtonPushed(app, event)
            %  ea=[1 2]  dof with enforced

            % num total dof
            % nem number of dof with enforced acceleration


            app.cdof=[];
            app.free_dof_array=[];
            app.tracking_array=[];
            app.TZ_tracking_array=[];
            app.constraint_matrix=[];
            app.ea=[];

            if(isempty(app.mass_unc))
                warndlg('Calcuate Natural Frequencies First');
                return;
            end    

            try
                pmass=app.mass_unc;
                pstiff=app.stiff_unc;
            catch
                warndlg('Calcuate Natural Frequencies First');
                return;
            end

            % mass
            % stiff
            
            
            sz=size(pmass);
            app.dof=sz(1);
            
            app.tracking_array=zeros(app.dof,1);
            app.free_dof_array=zeros(app.dof,1);
            
            for i=1:app.dof
                app.tracking_array(i)=i;
                app.free_dof_array(i)=i;
            end
            
            app.num_nodes_Tz_free=round(app.dof/2);
            
            app.TZ_tracking_array=zeros(app.num_nodes_Tz_free,1);
            
            for i=1:app.num_nodes_Tz_free
               app.TZ_tracking_array(i)=2*i-1;
            end

            disp('app.tracking_array')
            app.tracking_array

            disp('app.num_nodes_Tz_free')
            app.num_nodes_Tz_free

            disp('app.dof_status')
            app.dof_status

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            
            for i=1:app.num_nodes_Tz_free
                
                if( app.dof_status(2*i-1)==0 || app.dof_status(2*i)==0)
                    app.constraint_matrix(k,:)=[i 0 0];
                    
                    if(app.dof_status(2*i-1)==0)
                        app.constraint_matrix(k,2)=1;
                    end
                    if(app.dof_status(2*i)==0)
                        app.constraint_matrix(k,3)=1;
                    end     
                    
                    k=k+1;
                end    
                    
            end
            
            disp('app.constraint_matrix')
            app.constraint_matrix

            
            k=1;
            
            ibc=1;
            
            n=length(app.constraint_matrix(:,1));
                
            for i=1:n
                
                if(app.constraint_matrix(i,2)==1)
                    app.ea(k)=2*app.constraint_matrix(i,1)-1;
                    k=k+1;
                end
                    
                if(app.constraint_matrix(i,3)==1)
                    app.rot_constraint_matrix(ibc,1)=app.constraint_matrix(i,1);
                    app.rot_constraint_matrix(ibc,2)=0;
                    app.rot_constraint_matrix(ibc,3)=app.constraint_matrix(i,3);
                    ibc=ibc+1;
                end
                        
            end
            
            ibc=ibc-1;
            
            % constraint_matrix
            % ea
            
            if(ibc>=1)
            %    rot_constraint_matrix
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sz=size(app.constraint_matrix);
            
            nc=sz(1);

            fprintf('\n nc=%d \n',nc)
            
            k=1;
            for i=1:nc 
                
                 cmn=app.constraint_matrix(i,1);
                
                 if(app.constraint_matrix(i,2)==1)
                    app.cdof(k)=2*cmn-1;
                    k=k+1;
                 end    
                 if(app.constraint_matrix(i,3)==1)
                    app.cdof(k)=2*cmn;
                    k=k+1;
                 end   
            end
            
            app.cdof=sort(app.cdof,'descend');

            disp('app.cdof')
            app.cdof

            disp('app.free_dof_array')
            app.free_dof_array
            
            for i=1:length(app.cdof)
                fprintf(' i=%d  cdof=%d  free_dof_array=%d\n',i,app.cdof(i),length(app.free_dof_array));
                app.free_dof_array(app.cdof(i))=[];
            end    
            
            % cdof
            % free_dof_array
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            k=1;
            for i=1:ibc 
                 if(app.rot_constraint_matrix(i,3)==1)
                    app.rot_dof(k)=2*app.rot_constraint_matrix(i,1);
                    k=k+1;
                 end
            end
            
            try
                app.rot_dof=sort(app.rot_dof);
                ibc=length(app.rot_dof);  
            catch
                app.rot_dof=0;
                ibc=0;
            end          
  
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
          
            app.ea=fix_size(app.ea);

            sz=size(app.ea);

            app.eac=zeros(sz(1),3);
            
            app.eac(:,1)=app.ea;

            disp('app.ea')
            app.ea
            
            %  rot_dof
            
            if(ibc>=1)  % correct ea matrix
                
                app.rot_dof=sort(app.rot_dof,'descend');
                
                for i=1:ibc
                    for j=1:app.nem
                        if(app.ea(j)>app.rot_dof(i))
                            app.ea(j)=app.ea(j)-1;
            %%                out7=sprintf(' %d %d ',ea(j),rot_dof(i));
            %%                disp(out7);
                        end
                    end
                end
                
                app.drot_dof=sort(app.rot_dof,'descend');
                
                for i=1:ibc
                  app.tracking_array(app.drot_dof(i))=[];           
                end   
                
                for i=1:ibc
                    for j=1:app.num_nodes_Tz_free
                        if( app.TZ_tracking_array(j)>app.rot_dof(i) )
                               app.TZ_tracking_array(j)=app.TZ_tracking_array(j)-1;
                        end       
                    end
                end    
                
            end
            
            
            app.eac(:,2)=app.ea;
            
            for i=1:length(app.ea)
                app.eac(i,3)=app.eac(i,2)-app.eac(i,1);
            end
                
            app.ea=unique(app.ea);
            app.nem_ea=length(app.ea);
            
            if(app.nem_ea==0)
                warndlg('No drive dofs');
                return;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(ibc>=1)
                sz=size(app.rot_constraint_matrix);
            else
                sz(1)=0;
            end
                
            for i=sz(1):-1:1
                if(app.rot_constraint_matrix(i,3)==1)
                    p=2*app.rot_constraint_matrix(i,1);
                     pmass(:,p)=[];
                     pmass(p,:)=[];
                    pstiff(:,p)=[];
                    pstiff(p,:)=[];      
                end
            end
            
            sz=size(pmass);
            app.num=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            etype=1;
             
            % num total dof
            % nem number of dof with enforced acceleration
             
            disp(' Track Changes ');  
            
            % num = total dof - constrained rotational dof
            % ea = Tz dof enforced acceleration
            
            % fprintf(' nem=%d  num=%d  \n',nem,num);
            % ea
            
            [app.ngw]=track_changes(app.nem_ea,app.num,app.ea);
             
            dtype=2; % display partitioned matrices
            
            %% out1=sprintf('\n ** num=%d  nem=%d \n',num,nem);
            %% disp(out1);
            
            
            fprintf(' ibc=%d \n',ibc);
            
            disp(' Enforced Partition Matrices ');    
            
            [app.TT,app.T1,app.T2,app.Mwd,app.Mww,app.Kwd,app.Kww]=...
                            enforced_partition_matrices(app.num,app.ea,pmass,pstiff,etype,dtype);

            sz=size(app.Mwd);

            app.nem=sz(2);
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            disp(' Generalized Eigen ');            
                        
            [app.part_fn,app.part_omega,app.part_ModeShapes,app.part_MST]=Generalized_Eigen(app.Kww,app.Mww,2);   

            app.ipart=1;

            
                msgbox('Partitioning Complete');
                   
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            Clearfn(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
         
            Clearfn(app);
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
           
            Clearfn(app);
        end

        % Value changed function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: UniformNonstructuralMasslbmEditField
        function UniformNonstructuralMasslbmEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            
            Clearfn(app)
        end

        % Cell edit callback: UITable_msweep
        function UITable_msweepCellEdit(app, event)
  
            Clearfn(app);
        end

        % Button down function: UITable_msweep
        function UITable_msweepButtonDown(app, event)
            Clearfn(app);
        end

        % Button pushed function: CalculateBaseInputTrans
        function CalculateBaseInputTransButtonPushed(app, event)
           
            app.itype=1;

            if(isempty(app.TT) || app.ipart==0)
                warndlg('Partition Matrices first')
                return;
            end 

            app.fmin=str2num(app.StartFreqHzEditField.Value);
            app.fmax=str2num(app.EndFreqHzEditField.Value);

            pre_trans(app);

            pref=app.OutputArrayPrefixEditField.Value;

            if(app.chplot~=2)
                a=sprintf('%s_accel_max',pref);
                pv=sprintf('%s_pv_max',pref);
                rd=sprintf('%s_rel_disp_max',pref);
                st=sprintf('%s_stress_max',pref);

                assignin('base',a, app.ppp_a);
                assignin('base',pv, app.ppp_pv);
                assignin('base',rd, app.ppp_rd);
                assignin('base',st, app.ppp_st);
            end   

            if(app.chplot>=2)
                a=sprintf('%s_accel_node_%d',pref,app.node_near);
                pv=sprintf('%s_pv_node_%d',pref,app.node_near);
                rd=sprintf('%s_rel_disp_node_%d',pref,app.node_near);
                st=sprintf('%s_stress_node_%d',pref,app.node_near);

                assignin('base',a, app.qqq_a);
                assignin('base',pv, app.qqq_pv);
                assignin('base',rd, app.qqq_rd);
                assignin('base',st, app.qqq_st);            
            end   


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            app.itype=2;

            if(isempty(app.TT) || app.ipart==0)
                PartitionMatricesButtonPushed(app, event);
            end 

            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 


            [base_psd]=interp_psd_oct(app.THM(:,1),app.THM(:,2),app.freq);

            app.fmin=app.THM(1,1);
            app.fmax=app.THM(end,1);

            pre_trans(app);

            app.accel_psd = base_psd .* app.acc_trans.^2;
            app.pv_psd = base_psd .* app.pv_trans.^2;
            app.rd_psd = base_psd .* app.rd_trans.^2;
            app.stress_psd = base_psd .* app.stress_trans.^2;

            sz=size(app.accel_psd);

            accel_rms=zeros(sz(2),1);
            pv_rms=zeros(sz(2),1);
            rd_rms=zeros(sz(2),1);
            stress_rms=zeros(sz(2),1);

            ff=app.freq;

            [~,base_grms]=calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2));

            for j=1:sz(2) 
                [~,accel_rms(j)]=calculate_PSD_slopes_no( ff,app.accel_psd(:,j)  ); 
                [~,pv_rms(j)]=calculate_PSD_slopes_no( ff,app.pv_psd(:,j)  ); 
                [~,rd_rms(j)]=calculate_PSD_slopes_no( ff,app.rd_psd(:,j)  ); 
                [~,stress_rms(j)]=calculate_PSD_slopes_no( ff,app.stress_psd(:,j)  );           
            end

            md=5;
            x_label='Frequency (Hz)';

            if(app.chplot~=2)  % plot max
    
                [Ca,Ia]=max(accel_rms);
                [Cpv,Ipv]=max(pv_rms);            
                [Crd,Ird]=max(rd_rms);
                [Cst,Ist]=max(stress_rms);

                t_string=sprintf('Max Accel PSD  at %7.3g %s',app.xx(Ia),app.LU);
                y_label='Accel (G^2/Hz)';

                ppp1=[app.freq app.accel_psd(:,Ia)];
                ppp2=app.THM;

                a_psd_max=ppp1;

                leg1=sprintf('Response  %7.3g GRMS',Ca);
                leg2=sprintf('Base Input  %7.3g GRMS',base_grms);

                [app.fig_num,h2]=plot_loglog_function_md_two_h2(app.fig_num,x_label,...
                    y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);

                %%

                pv_psd_max=[app.freq app.pv_psd(:,Ipv)];

                if(app.iu==1)
                    t_string=sprintf('Max Pseudo Velocity PSD \n %7.3g in/sec RMS at %7.3g %s',Cpv,app.xx(Ipv),app.LU);
                    y_label='Velocity [(in/sec)^2/Hz]';
                else
                    t_string=sprintf('Max Pseudo Velocity PSD \n %7.3g m/sec RMS at %7.3g %s',Cpv,app.xx(Ipv),app.LU);
                    y_label='Velocity [(m/sec)^2/Hz]';
                end    

                [app.fig_num,~]=...
                    plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,pv_psd_max,app.fmin,app.fmax,md); 

                %%

                rd_psd_max=[app.freq app.rd_psd(:,Ird)];

                if(app.iu==1)
                    t_string=sprintf('Max Relative Displacement PSD \n %7.3g in RMS at %7.3g %s',Crd,app.xx(Ird),app.LU);
                    y_label='Rel Disp (in^2/Hz)';
                else
                    t_string=sprintf('Max Relative Displacement PSD \n %7.3g m RMS at %7.3g %s',Crd,app.xx(Ird),app.LU);
                    y_label='Rel Disp (m^2/Hz)';
                end    

                [app.fig_num,~]=...
                    plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,rd_psd_max,app.fmin,app.fmax,md); 

                %%

                stress_psd_max=[app.freq app.stress_psd(:,Ist)];

                if(app.iu==1)
                    t_string=sprintf('Max Stress PSD \n %7.3g psi RMS at %7.3g %s',Cst,app.xx(Ist),app.LU);
                    y_label='Stress (psi^2/Hz)';
                else
                    t_string=sprintf('Max Stress PSD \n %7.3g Pa RMS at %7.3g %s',Cst,app.xx(Ist),app.LU);
                    y_label='Velocity (Pa^2/Hz)';
                end    

                if(min(stress_psd_max(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,stress_psd_max,app.fmin,app.fmax,md); 
                end

                %%

            end
            if(app.chplot>=2)  % plot x location

                xr=str2num(app.ResponseLocationfromLeftEndEditField.Value);
                [~,app.node_near]=min(abs(app.xx-xr));

                Ca=accel_rms(app.node_near);
                Cpv=pv_rms(app.node_near);               
                Crd=rd_rms(app.node_near);
                Cst=stress_rms(app.node_near);                

                t_string=sprintf('Accel PSD  at %7.3g %s',app.xx(app.node_near),app.LU);
                y_label='Accel (G^2/Hz)';

                ppp1=[app.freq app.accel_psd(:,app.node_near)];
                ppp2=app.THM;

                a_psd_x=ppp1;

                leg1=sprintf('Response  %7.3g GRMS',Ca);
                leg2=sprintf('Base Input  %7.3g GRMS',base_grms);

                [app.fig_num,h2]=plot_loglog_function_md_two_h2(app.fig_num,x_label,...
                    y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);

                %%

                pv_psd_x=[app.freq app.pv_psd(:,app.node_near)];

                if(app.iu==1)
                    t_string=sprintf('Pseudo Velocity PSD \n %7.3g in/sec RMS at %7.3g %s',Cpv,app.xx(app.node_near),app.LU);
                    y_label='Velocity [(in/sec)^2/Hz]';
                else
                    t_string=sprintf('Pseudo Velocity PSD \n %7.3g m/sec RMS at %7.3g %s',Cpv,app.xx(app.node_near),app.LU);
                    y_label='Velocity [(m/sec)^2/Hz]';
                end    

                if(min(pv_psd_max(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,pv_psd_x,app.fmin,app.fmax,md); 
                end
                %%

                rd_psd_x=[app.freq app.rd_psd(:,app.node_near)];

                if(app.iu==1)
                    t_string=sprintf('Relative Displacement PSD \n %7.3g in RMS at %7.3g %s',Crd,app.xx(app.node_near),app.LU);
                    y_label='Rel Disp (in^2/Hz)';
                else
                    t_string=sprintf('Relative Displacement PSD \n %7.3g m RMS at %7.3g %s',Crd,app.xx(app.node_near),app.LU);
                    y_label='Rel Disp (m^2/Hz)';
                end    

                if(min(rd_psd_x(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,rd_psd_x,app.fmin,app.fmax,md); 
                end

                %%

                stress_psd_x=[app.freq app.stress_psd(:,app.node_near)];

                if(app.iu==1)
                    t_string=sprintf('Stress PSD \n %7.3g psi RMS at %7.3g %s',Cst,app.xx(app.node_near),app.LU);
                    y_label='Stress (psi^2/Hz)';
                else
                    t_string=sprintf('Stress PSD \n %7.3g Pa RMS at %7.3g %s',Cst,app.xx(app.node_near),app.LU);
                    y_label='Velocity (Pa^2/Hz)';
                end    

                if(min(stress_psd_x(:,2))>0)
                    [app.fig_num,~]=...
                        plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,stress_psd_x,app.fmin,app.fmax,md); 
                end
                %%

            end

            pref=app.OutputPSDArrayPrefixEditField.Value;

            if(app.chplot~=2)
                a=sprintf('%s_accel_max',pref);
                pv=sprintf('%s_pv_max',pref);
                rd=sprintf('%s_rel_disp_max',pref);
                st=sprintf('%s_stress_max',pref);

                assignin('base',a, a_psd_max);
                assignin('base',pv, pv_psd_max);
                assignin('base',rd, rd_psd_max);
                assignin('base',st, stress_psd_max);        
            end   

            if(app.chplot>=2)
                a=sprintf('%s_accel_node_%d',pref,app.node_near);
                pv=sprintf('%s_pv_node_%d',pref,app.node_near);
                rd=sprintf('%s_rel_disp_node_%d',pref,app.node_near);
                st=sprintf('%s_stress_node_%d',pref,app.node_near);

                assignin('base',a, a_psd_x);
                assignin('base',pv, pv_psd_x);
                assignin('base',rd, rd_psd_x);
                assignin('base',st, stress_psd_x);            
            end   


        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output_stiff=app.StiffnessArrayEditField.Value;
            output_mass=app.MassArrayEditField.Value;

            assignin('base',output_stiff, app.stiff);
            assignin('base',output_mass, app.mass);

            msgbox('Arrays saved');
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)

            ChoosePlots(app);
        end

        % Button down function: TransmissibilityTab
        function TransmissibilityTabButtonDown(app, event)
            if(app.ipart==0)
                warndlg('Partition Matrices first');
                return;
            end    
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            ChangeUnits(app);

        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: SelectInputArrayListBox_psd
        function SelectInputArrayListBox_psdValueChanged(app, event)
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
            
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)

            multispan_beam_FEA.numi=app.NumberListBox.Value;
            multispan_beam_FEA.num_modes=app.NumberofModestoIncludeListBox.Value;
            multispan_beam_FEA.x=app.ResponseLocationfromLeftEndEditField.Value;
            multispan_beam_FEA.L=app.LengthinEditField.Value;
            multispan_beam_FEA.E=app.ElasticModuluspsiEditField.Value;
            multispan_beam_FEA.rho=app.MassDensitylbmin3EditField.Value;
            multispan_beam_FEA.E1=app.EditField_1.Value;
            multispan_beam_FEA.E2=app.EditField_2.Value;
            multispan_beam_FEA.E3=app.EditField_3.Value;
            multispan_beam_FEA.E4=app.EditField_4.Value;
            multispan_beam_FEA.nsm=app.UniformNonstructuralMasslbmEditField.Value;
            multispan_beam_FEA.ne=app.NumberofElementsEditField.Value;
            multispan_beam_FEA.fmin=app.StartFreqHzEditField.Value;           
            multispan_beam_FEA.fmax=app.EndFreqHzEditField.Value;
            multispan_beam_FEA.nmn=app.DampingTypeListBox.Value;
            multispan_beam_FEA.nD=app.ModalDistributionListBox.Value;
            multispan_beam_FEA.cross=app.CrossSectionListBox.Value;
            multispan_beam_FEA.plot=app.PlotListBox.Value;
            multispan_beam_FEA.unit=app.UnitsListBox.Value;
            multispan_beam_FEA.iu=app.iu;
            multispan_beam_FEA.mat=app.MaterialListBox.Value;
            multispan_beam_FEA.nL=app.LeftBCListBox.Value;
            multispan_beam_FEA.nR=app.RightBCListBox.Value;  
            multispan_beam_FEA.dd=app.UITable.Data;
            multispan_beam_FEA.mdata=app.UITable_msweep.Data;
            
            % % %
             
             structnames = fieldnames(multispan_beam_FEA, '-full'); % fields in the struct
              
            % % %
             
                [writefname, writepname] = uiputfile('*.mat','Save data as');
               
                elk=sprintf('%s%s',writepname,writefname);
            
            try
                save(elk, 'multispan_beam_FEA'); 
            catch
                warndlg('Save error');
                return;
            end
             
            disp(' ');
            out1=sprintf('Save Complete: %s',writefname);
            disp(out1);
            msgbox(out1);

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
            [filename, pathname] = uigetfile('*.mat');
             
            NAME = [pathname,filename];
             
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
             
            disp(' ref 2');
             
            k=length(structnames);
             
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
             
            disp(' ref 3');
             
            structnames
             
             
            % struct
             
            try
                multispan_beam_FEA=evalin('base','multispan_beam_FEA');
            catch
                warndlg(' evalin failed ');
                return;
            end


            app.NumberListBox.Value=multispan_beam_FEA.numi;
            app.NumberofModestoIncludeListBox.Value=multispan_beam_FEA.num_modes;
            app.ResponseLocationfromLeftEndEditField.Value=multispan_beam_FEA.x;
            app.LengthinEditField.Value=multispan_beam_FEA.L;

            app.ElasticModuluspsiEditField.Value=multispan_beam_FEA.E;
            app.MassDensitylbmin3EditField.Value=multispan_beam_FEA.rho;
            app.EditField_1.Value=multispan_beam_FEA.E1;
            app.EditField_2.Value=multispan_beam_FEA.E2;
            app.EditField_3.Value=multispan_beam_FEA.E3;
            app.EditField_4.Value=multispan_beam_FEA.E4;

            app.UniformNonstructuralMasslbmEditField.Value=multispan_beam_FEA.nsm;
            
            app.NumberofElementsEditField.Value=multispan_beam_FEA.ne;

            app.StartFreqHzEditField.Value=multispan_beam_FEA.fmin;           
            app.EndFreqHzEditField.Value=multispan_beam_FEA.fmax;
            app.DampingTypeListBox.Value=multispan_beam_FEA.nmn;
            app.ModalDistributionListBox.Value=multispan_beam_FEA.nD;

            app.CrossSectionListBox.Value=multispan_beam_FEA.cross;

            app.PlotListBox.Value=multispan_beam_FEA.plot;
            app.UnitsListBox.Value=multispan_beam_FEA.unit;
            app.iu=multispan_beam_FEA.iu;

            app.MaterialListBox.Value=multispan_beam_FEA.mat;
            app.LeftBCListBox.Value=multispan_beam_FEA.nL;
            app.RightBCListBox.Value=multispan_beam_FEA.nR;  
            app.UITable.Data=multispan_beam_FEA.dd;
            app.UITable_msweep.Data=multispan_beam_FEA.mdata;

            ChangeUnits(app);
            CloseFigures(app);
            ChangeCrossSection(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            msweep(app);
            ChoosePlots(app);
            update_psd(app);

        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
             Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultispanBeamFEAUIFigure = uifigure('Visible', 'off');
            app.MultispanBeamFEAUIFigure.Name = 'Multispan Beam FEA';
            app.multispan_beam_bending_FEAver12byTomIrvineLabel = uilabel(app.MultispanBeamFEAUIFigure);
            app.multispan_beam_bending_FEAver12byTomIrvineLabel.Text = 'multispan_beam_bending_FEA  ver 1.2   by Tom Irvine';
            app.TabGroup = uitabgroup(app.MultispanBeamFEAUIFigure);
            app.GeometryBCsTab = uitab(app.TabGroup);
            app.GeometryBCsTab.Title = 'Geometry & BCs';
            app.Image = uiimage(app.GeometryBCsTab);
            app.Image.ImageSource = 'beam_image_2.jpg';
            app.ImageIBeam = uiimage(app.GeometryBCsTab);
            app.ImageIBeam.ImageSource = 'Ibeam1.jpg';
            app.CrossSectionListBoxLabel = uilabel(app.GeometryBCsTab);
            app.CrossSectionListBoxLabel.Text = 'Cross-Section';
            app.CrossSectionListBox = uilistbox(app.GeometryBCsTab);
            app.CrossSectionListBox.Items = {'Rectangular', 'Pipe', 'Solid Cylinder', 'I-Beam', 'Other'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Solid Cylinder';
            app.LeftBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryBCsTab);
            app.LeftBCListBox.Items = {'Free', 'Pinned', 'Fixed'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Pinned';
            app.RightBCListBoxLabel = uilabel(app.GeometryBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryBCsTab);
            app.RightBCListBox.Items = {'Free', 'Pinned', 'Fixed'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Pinned';
            app.LengthinEditFieldLabel = uilabel(app.GeometryBCsTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryBCsTab, 'text');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.EditField_1Label = uilabel(app.GeometryBCsTab);
            app.EditField_1Label.Text = 'Edit Field';
            app.EditField_1 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_2Label = uilabel(app.GeometryBCsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3Label = uilabel(app.GeometryBCsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.GeometryBCsTab);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GeometryBCsTab, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.NumberofElementsLabel = uilabel(app.GeometryBCsTab);
            app.NumberofElementsLabel.Text = 'Number of Elements *';
            app.NumberofElementsEditField = uieditfield(app.GeometryBCsTab, 'text');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.Label = uilabel(app.GeometryBCsTab);
            app.Label.Text = {'* Increase number of elements until response '; '  converges, especially for stress'};
            app.IntermediatePinnedLocationsLabel = uilabel(app.GeometryBCsTab);
            app.IntermediatePinnedLocationsLabel.Text = 'Intermediate Pinned Locations';
            app.NumberListBoxLabel = uilabel(app.GeometryBCsTab);
            app.NumberListBoxLabel.Text = 'Number';
            app.NumberListBox = uilistbox(app.GeometryBCsTab);
            app.NumberListBox.Items = {'0', '1', '2', '3', '4', '5'};
            app.NumberListBox.ValueChangedFcn = createCallbackFcn(app, @NumberListBoxValueChanged, true);
            app.NumberListBox.Value = '1';
            app.UITable_msweep = uitable(app.GeometryBCsTab);
            app.UITable_msweep.ColumnName = {'N'; 'x'};
            app.UITable_msweep.RowName = {};
            app.UITable_msweep.ColumnEditable = [false true];
            app.UITable_msweep.CellEditCallback = createCallbackFcn(app, @UITable_msweepCellEdit, true);
            app.UITable_msweep.ButtonDownFcn = createCallbackFcn(app, @UITable_msweepButtonDown, true);
            app.DistancefromLeftEndLabel = uilabel(app.GeometryBCsTab);
            app.DistancefromLeftEndLabel.Text = 'Distance from Left End';
            app.UnitsListBoxLabel = uilabel(app.GeometryBCsTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryBCsTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MaterialAddedMassTab = uitab(app.TabGroup);
            app.MaterialAddedMassTab.Title = 'Material & Added Mass';
            app.MaterialListBoxLabel = uilabel(app.MaterialAddedMassTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialAddedMassTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNonstructuralMasslbmEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.UniformNonstructuralMasslbmEditField.ValueChangedFcn = createCallbackFcn(app, @UniformNonstructuralMasslbmEditFieldValueChanged, true);
            app.UniformNonstructuralMasslbmEditField.Value = '0';
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangedFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanged, true);
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialAddedMassTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialAddedMassTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangedFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanged, true);
            app.NaturalFrequenciesTab = uitab(app.TabGroup);
            app.NaturalFrequenciesTab.Title = 'Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.CalculateNaturalFrequenciesButton = uibutton(app.NaturalFrequenciesTab, 'push');
            app.CalculateNaturalFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesButtonPushed, true);
            app.CalculateNaturalFrequenciesButton.Text = 'Calculate Natural Frequencies ';
            app.SelectModeforPlotListBoxLabel = uilabel(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.NaturalFrequenciesTab);
            app.SelectModeforPlotListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.SelectModeforPlotListBox.Value = '1';
            app.UITable_fn = uitable(app.NaturalFrequenciesTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'Participation Factor'; 'Modal Mass Ratio'; 'Phase Speed (ft/sec)'; 'Wavenumber (rad/ft)'};
            app.UITable_fn.RowName = {};
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.BaseExcitationResponseTab = uitab(app.TabGroup);
            app.BaseExcitationResponseTab.Title = 'Base Excitation Response';
            app.TabGroup2 = uitabgroup(app.BaseExcitationResponseTab);
            app.PartitionMatricesTab = uitab(app.TabGroup2);
            app.PartitionMatricesTab.Title = 'Partition Matrices';
            app.PartitionMatricesButton = uibutton(app.PartitionMatricesTab, 'push');
            app.PartitionMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @PartitionMatricesButtonPushed, true);
            app.PartitionMatricesButton.Text = 'Partition Matrices';
            app.TransmissibilityTab = uitab(app.TabGroup2);
            app.TransmissibilityTab.Title = 'Transmissibility';
            app.TransmissibilityTab.ButtonDownFcn = createCallbackFcn(app, @TransmissibilityTabButtonDown, true);
            app.CalculateBaseInputTrans = uibutton(app.TransmissibilityTab, 'push');
            app.CalculateBaseInputTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputTransButtonPushed, true);
            app.CalculateBaseInputTrans.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.StartFreqHzEditField.Value = '10';
            app.EndFreqHzEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.EndFreqHzEditField.Value = '2000';
            app.OutputArrayPrefixEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.OutputArrayPrefixEditFieldLabel.Text = 'Output Array Prefix';
            app.OutputArrayPrefixEditField = uieditfield(app.TransmissibilityTab, 'text');
            app.OutputArrayPrefixEditField.Value = 'mspan_trans';
            app.PSDInputTab = uitab(app.TabGroup2);
            app.PSDInputTab.Title = 'PSD Input';
            app.CalculateButton = uibutton(app.PSDInputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.listbox_method_psd = uilistbox(app.PSDInputTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.PSDInputTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.PSDInputTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.SelectInputArrayListBox_2Label = uilabel(app.PSDInputTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.PSDInputTab);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.SelectInputArrayListBox_psd.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_psdValueChanged, true);
            app.UpdateListButton_psd = uibutton(app.PSDInputTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.OutputPSDArrayPrefixEditFieldLabel = uilabel(app.PSDInputTab);
            app.OutputPSDArrayPrefixEditFieldLabel.Text = 'Output PSD Array Prefix';
            app.OutputPSDArrayPrefixEditField = uieditfield(app.PSDInputTab, 'text');
            app.OutputPSDArrayPrefixEditField.Value = 'mspan_psd';
            app.NumberofModestoIncludeLabel = uilabel(app.BaseExcitationResponseTab);
            app.NumberofModestoIncludeLabel.Text = {'Number of '; 'Modes '; 'to Include'; ''};
            app.NumberofModestoIncludeListBox = uilistbox(app.BaseExcitationResponseTab);
            app.NumberofModestoIncludeListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModestoIncludeListBox.Value = '12';
            app.ResponseLocationfromLeftEndLabel = uilabel(app.BaseExcitationResponseTab);
            app.ResponseLocationfromLeftEndLabel.Text = {'Response Location '; 'from Left End'};
            app.ResponseLocationfromLeftEndEditField = uieditfield(app.BaseExcitationResponseTab, 'text');
            app.PlotListBoxLabel = uilabel(app.BaseExcitationResponseTab);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.BaseExcitationResponseTab);
            app.PlotListBox.Items = {'Maximums', 'Response Location Only', 'Maximums & Response Locations'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Maximums';
            app.ScriptwillfindnearestnodelocationLabel = uilabel(app.BaseExcitationResponseTab);
            app.ScriptwillfindnearestnodelocationLabel.Text = 'Script will find nearest node location.';
            app.inchLabel = uilabel(app.BaseExcitationResponseTab);
            app.inchLabel.Text = 'inch';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MassArrayEditFieldLabel = uilabel(app.ExportTab);
            app.MassArrayEditFieldLabel.Text = 'Mass Array';
            app.MassArrayEditField = uieditfield(app.ExportTab, 'text');
            app.MassArrayEditField.Value = 'mspan_mass';
            app.StiffnessArrayEditFieldLabel = uilabel(app.ExportTab);
            app.StiffnessArrayEditFieldLabel.Text = 'Stiffness Array';
            app.StiffnessArrayEditField = uieditfield(app.ExportTab, 'text');
            app.StiffnessArrayEditField.Value = 'mspan_stiffness';
            app.SaveModelButton = uibutton(app.MultispanBeamFEAUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.MultispanBeamFEAUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            % Show the figure after all components are created
            app.MultispanBeamFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multispan_beam_bending_FEA

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MultispanBeamFEAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MultispanBeamFEAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultispanBeamFEAUIFigure)
        end
    end
end