classdef nm_mdof_arbitrary_enforced_motion_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nm_mdof_arbitrary_enforced_motionUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MassStiffnessTab                matlab.ui.container.Tab
        StiffnessArrayListBoxLabel      matlab.ui.control.Label
        StiffnessArrayListBox           matlab.ui.control.ListBox
        MassArrayListBox                matlab.ui.control.ListBox
        MassArrayListBoxLabel           matlab.ui.control.Label
        ReadMassStiffnessButton         matlab.ui.control.Button
        UpdateListsButton               matlab.ui.control.Button
        UnitsListBox                    matlab.ui.control.ListBox
        ForceMassDampingStiffnessUnitsListBoxLabel  matlab.ui.control.Label
        DampingTab                      matlab.ui.container.Tab
        DampingMethodListBox            matlab.ui.control.ListBox
        DampingMethodListBoxLabel       matlab.ui.control.Label
        DampingTabGroup                 matlab.ui.container.TabGroup
        CarrayTab                       matlab.ui.container.Tab
        DampingCoefficientArrayListBox  matlab.ui.control.ListBox
        DampingCoefficientArrayListBoxLabel  matlab.ui.control.Label
        UpdateCListButton               matlab.ui.control.Button
        ModalTab                        matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EnforcedMotiondofTab            matlab.ui.container.Tab
        EnforceddofNumberbyArrayColumnLabel  matlab.ui.control.Label
        UITable_edof                    matlab.ui.control.Table
        NumberofdofwithEnforcedMotionListBox  matlab.ui.control.ListBox
        NumberofdofwithEnforcedMotionLabel  matlab.ui.control.Label
        EnforcedDisplacementTab         matlab.ui.container.Tab
        CalculateButton_response        matlab.ui.control.Button
        UpdateDisplacementListButton    matlab.ui.control.Button
        DisplacementArrayListBox        matlab.ui.control.ListBox
        DisplacementArrayListBoxLabel   matlab.ui.control.Label
        TheDispLabel                    matlab.ui.control.Label
        EnforcedAccelerationTab         matlab.ui.container.Tab
        TheAccelLabel_2                 matlab.ui.control.Label
        AccelerationArrayListBox        matlab.ui.control.ListBox
        AccelerationArrayListBoxLabel   matlab.ui.control.Label
        UpdateAccelerationListButton    matlab.ui.control.Button
        CalculateButton_response_2      matlab.ui.control.Button
        ViewResultsTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ModalTab_3                      matlab.ui.container.Tab
        EnforcedMotionSystemModeShapesTextArea  matlab.ui.control.TextArea
        EnforcedMotionSystemModeShapesTextAreaLabel  matlab.ui.control.Label
        UnenforcedMotionSystemNaturalFrequenciesHzTextArea  matlab.ui.control.TextArea
        NaturalFrequenicesHzReadOnlyLabel  matlab.ui.control.Label
        EnforcedMotionSystemNaturalFrequenciesHzTextArea  matlab.ui.control.TextArea
        EnforcedMotionSystemNaturalFrequenciesHzTextAreaLabel  matlab.ui.control.Label
        ResponseTab                     matlab.ui.container.Tab
        DisplacementTextArea            matlab.ui.control.TextArea
        DisplacementTextArea_2Label     matlab.ui.control.Label
        VelocityTextArea                matlab.ui.control.TextArea
        VelocityTextAreaLabel           matlab.ui.control.Label
        AccelerationTextArea            matlab.ui.control.TextArea
        AccelerationTextAreaLabel       matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        OutputdofNumberEditField        matlab.ui.control.EditField
        OutputdofNumberLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iu                  % Description
        force_acceleration
        acceleration
        velocity
        displacement
        idamp 
        MM
        M
        K
        ndof
        dm
        num_edof
        edof
        fn
        omega
        MST
        Mwd
        Cwd
        TT
        KT
        CT
        MT
        C
        ngw
        free_dof
        nfree
        ModeShapes
        nem
        Mww
        num_modes
    end
    
    methods (Access = private)

        function PostPartition(app)

            app.ngw=zeros(app.ndof,1);

            app.nfree=length(app.free_dof);
            
            for i=1:app.nem
                app.ngw(i)=app.edof(i);
            end
            for i=1:app.nfree
                app.ngw(i+app.nem)=app.free_dof(i);
            end
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
            clear r;
            clear part;
            clear ModalMass;
            %
            sz=size(app.Mww);
            %
            n_app.free_dof=sz(1);
            %
            app.num_modes=n_app.free_dof;
            %
            clear temp;
            temp=app.ModeShapes(:,1:app.num_modes);
            clear app.ModeShapes;
            app.ModeShapes=temp;
            app.MST=app.ModeShapes';
        end    

        function Damping_Read(app)
            ndamp=app.idamp;
            
            if(ndamp==1)
            
                FS=app.DampingCoefficientArrayListBox.Value;
                app.C=evalin('base',FS);     
            
                if(isempty(app.C))
                    warndlg('enter damping coefficient'); 
                    return; 
                end    
                
            else
                
                if(isempty(app.dm))
                    warndlg('Enter damping first');
                    return;
                end    
                   
                dampv=app.dm;
            
                app.C=zeros(app.ndof,app.ndof);

                omegan=2*pi*app.fn;

                size(omegan)
                size(dampv)
            
                for i=1:app.ndof
                    app.C(i,i)=2*dampv(i)*omegan(i);
                end

                disp('MM')
                size(app.MM)
                disp('ModeShapes')
                size(app.ModeShapes)
                size(app.ndof)
                size(app.MST)
            
                app.C=app.MM*app.ModeShapes*app.C*app.MST*app.MM;   
               
            end

        end    

        function prep(app)

            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));

            ClosePlots(app);
            get_edof(app);

        end

        function get_edof(app)
            B=app.UITable_edof.Data;

            app.edof=B;

            fprintf('  app.edof(1)=%d \n',app.edof(1));
        end    

        function enforced_dof(app)
            app.num_edof=str2num(app.NumberofdofwithEnforcedMotionListBox.Value);
            
            q=zeros(app.num_edof,1);
            
            app.UITable_edof.Data=q;
        end    

        function UpdateC(app)
            [cell_array]=update_2D_array();

            try
                app.DampingCoefficientArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.DampingCoefficientArrayListBox.Items);
                app.DampingCoefficientArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            try
                app.DampingCoefficientArrayListBox.Value=app.DampingCoefficientArrayListBox.Items{1};
            catch
            end
        end          

        function UpdateDisp(app)
            [cell_array]=update_time_history()

            try
                app.DisplacementArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.DisplacementArrayListBox.Items);
                app.DisplacementArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            try
                app.DisplacementArrayListBox.Value=app.DisplacementArrayListBox.Items{1};
            catch
            end

            %%%%

            try
                app.AccelerationArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.AccelerationArrayListBox.Items);
                app.AccelerationArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            try
                app.AccelerationArrayListBox.Value=app.AccelerationArrayListBox.Items{1};
            catch
            end

        end    

        function UpdateMK(app)
            [cell_array]=update_2D_array();

            try
                app.MassArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.MassInputArrayListBox.Items);
                app.MassInputArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.MassArrayListBox.Value=app.MassArrayListBox.Items{1};

            %%

            try
                app.StiffnessArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.StiffnessInputArrayListBox.Items);
                app.StiffnessArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.StiffnessArrayListBox.Value=app.StiffnessArrayListBox.Items{1};

        end

        function Units(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.TheDispLabel.Text='The displacement array must have two columns:  time (sec) & displacement (in)';
            else
                app.TheDispLabel.Text='The displacement array must have two columns:  time (sec) & displacement (mm)';
            end

        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

      function ChangeDamping(app)

            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));
          
            if(isempty(app.M))
                warndlg('Read Mass array first');
                return;
            end    

            try
                sz=size(app.M);
                app.ndof=sz(1);
            catch
                app.ndof=1;
            end

            if(app.ndof==0)
                app.ndof=1;
            end    

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else

                for i=1:app.ndof
                    ssr{i}=sprintf('Mode %d',i);
                    ssq{i}='10';
                    ssd{i}='0.05';
                end    

                app.UITable.RowName=ssr';
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData=ssq';
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData=ssd';                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style

            ClosePlots(app);
            Units(app);
            UpdateDisp(app);
            UpdateMK(app);
            UpdateC(app);
            enforced_dof(app);

             s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_edof,s) % adjust the style           

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
           
            Units(app);
        end

        % Button pushed function: UpdateListsButton
        function UpdateListsButtonPushed(app, event)
            UpdateMK(app);
        end

        % Button pushed function: CalculateButton_response
        function CalculateButton_responsePushed(app, event)

            prep(app);

            try
                FS=app.DisplacementArrayListBox.Value;
                EA=evalin('base',FS); 
            catch
                warndlg('Displacement array read failed');
                return;
            end
            
            if(isempty(app.M))
               warndlg('Read mass array'); 
               return; 
            end  
            if(isempty(app.K))
               warndlg('Read stiffness array'); 
               return; 
            end 

            if(app.iu==2)  % lbm
                app.MM=app.M/386;
            end

            %%%%
            
            enforced_dof=app.edof;

            for i=1:length(enforced_dof)
                if(enforced_dof(i)==0)
                    warndlg('Enforced Motion dof must be > 0')
                    return;
                end    
            end  

            num_ea=length(app.edof);
            
            szM=size(app.M);
            szEA=size(EA);
            
            NT=szEA(1);
            nt=NT;
            
            FFI=EA(:,2:szEA(2));
            
            if(app.iu==3)
                FFI=FFI/1000;  % convert mm to m
            end
            
            if((szEA(2)-1) ~= num_ea)
               warndlg('number of enforced dof not equal to input array columns'); 
               return; 
            end    
            
            app.ndof=szM(1);
            
            dur=EA(NT,1)-EA(1,1);
           
            dt=mean(diff(EA(:,1)));
            
            fprintf(' NT=%d  dur=%7.3g sec  dt=%7.3g sec \n',NT,dur,dt);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.K,app.MM,1);

            fn_orig=app.fn;

            Damping_Read(app);
            
            %%
            
            mass=app.MM;
            cdamping=app.C;
            stiff=app.K;
            
            cdamping
            
            disp_dof=zeros(app.ndof,1);
            
            for i=1:num_ea
                disp_dof(app.edof(i))=1;
            end

            num=length(disp_dof);
            
            forig=zeros(nt,num_ea);
            %
            k=1;
            for i=1:length(disp_dof)
                nfi=disp_dof(i);
                if(nfi>0)
                    forig(:,k)=FFI(:,nfi);
                    k=k+1;
                end    
            end
            
            j=1;
            k=1;

            for i=1:length(disp_dof)
                if(disp_dof(i)>0)
                    enforced_dof(k)=i;
                    k=k+1;
                else
                    app.free_dof(j)=i;
                    j=j+1;
                end
            end
            
            app.nem=length(enforced_dof);
            app.nfree=length(app.free_dof);
            
            dispm=1;
            
            [app.fn,app.omega,app.ModeShapes,app.MST,app.Mwd,app.Cwd,Kwd,app.Mww,Cww,Kww,app.TT,T1,T2,app.KT,app.CT,app.MT]=...
               partition_matrices_mck(mass,cdamping,stiff,num,app.nfree,app.nem,app.free_dof,...
                                                           enforced_dof,'disp',dispm);
            %

            PostPartition(app);

            %
            % r=ones(app.num_modes,1);
            %
            % part = MST*app.Mww*r;
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  displacement
            %
            %  d
            %  v
            %  acc
            %
            %%
            n=app.num_modes;
            %
            %
            %%
            
            [a0,a1,a2,a3,a4,a5,a6,a7]=Newmark_coefficients(dt);
            %
            KH=Kww+a0*app.Mww+a1*Cww;
            %
            inv_KH=pinv(KH); 
            
            %
            t=zeros(nt,1);
            d=zeros(nt,app.nfree);
            % v=zeros(nt,app.free);
            % acc=zeros(nt,app.free);
            %
            U=zeros(n,1);
            Ud=zeros(n,1);
            Udd=zeros(n,1);
         
            %%
            
            progressbar
            for i=1:(nt-1)
                progressbar(i/(nt-1));
            %   
                FP=-Kwd*(FFI(i,:))';
            %    
                FH=FP+app.Mww*(a0*U+a2*Ud+a3*Udd)+Cww*(a1*U+a4*Ud+a5*Udd);
            %
            %%    Un=KH\FH;
            %
                Un=inv_KH*FH;
            %
                Uddn=a0*(Un-U)-a2*Ud-a3*Udd;
                Udn=Ud+a6*Udd+a7*Uddn;
                U=Un;
                Ud=Udn;
                Udd=Uddn;
            %
                d(i+1,:)=U';
            %%    v(i+1,:)=Ud';
            %%    acc(i+1,:)=Udd';
                t(i+1)=i*dt;   
            %
            end
            progressbar(1);
               
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    Transformation back to xd xf
            %
            %%    dT=TT*d';
            %%    vT=TT*v';
            %%    accT=TT*acc';
            %
            dT=(T1*FFI' + T2*d')';
            %% vT=(T1*dvelox' + T2*v')';
            %% accT=(T1*FFI' + T2*acc')';
            %
            %  Put in original order
            %
            
            ZdT=[ forig  dT ];
            %% ZvT=[ dvelox  vT ];
            %% ZaccT=[ forig  accT ];
            %
            clear d;   
            clear v;
            clear acc;
            
            d=zeros(nt,num);
            %% v=zeros(nt,num);
            %% acc=zeros(nt,num);
            
            %
            for i=1:num
               for j=1:nt
                    d(j,app.ngw(i))=  (ZdT(j,i));        
            %%        v(j,app.ngw(i))=  (ZvT(j,i));     
            %%      acc(j,app.ngw(i))=(ZaccT(j,i));
               end
            end
            %% x=d;
            %
          
             d=real(d);

            %%% leave after mdof_plot
            
            clear acceleration;
            clear velocity;
            clear displacement;
            %

            sz=size(d);

            v=zeros(sz(1),sz(2));
            a=zeros(sz(1),sz(2));

            for i=1:sz(2)
                v(:,i)=differentiate_function(d(:,i),dt);
            end
            for i=1:sz(2)
                a(:,i)=differentiate_function(v(:,i),dt);
            end          

            v=fix_size(v);
            a=fix_size(a);
            
            if(app.iu<=2)
                ylabel1='Disp (in)'; 
                a=a/386;
            else
                ylabel1='Disp (mm)'; 
                
                d=d*1000;
                a=a/9.81;
            end
            
            ylabel2=ylabel1;
            ylabel3=ylabel1;   
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [colororder,color_rows]=line_colors();

                figure(1);
            
                hold('all');
            %
                j=1;
                for i = 1:num
                    if(j>color_rows)
                        j=j-color_rows;
                    end
                    out1=sprintf('dof %d',i);
                    plot(t,d(:,i),'Color', colororder(j,:),'DisplayName',out1);
                    legend('-DynamicLegend');
                    j=j+1;
                end
                hold off;
            %%
                xlabel('Time(sec)');
                title('Displacement');
                
                if(app.iu<=2)
                    ylabel('Disp (in)');
                else
                    ylabel('Disp (mm)');
                end
               
                grid on;
                hold off;
            
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            
                fig_num=2;
            
                try
                       data1=[t d(:,1)];
                catch       
                end
                try
                       data2=[t d(:,2)];
                catch       
                end
                try
                       data3=[t d(:,3)];
                catch       
                end
            
                
                xlabel2='Time(sec)';
                xlabel3='Time(sec)';    
                    
                t_string1='Displacement dof 1';
                t_string2='Displacement dof 2';
                t_string3='Displacement dof 3';    
            
                if(num==2)
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
                end
                if(num==3)
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel2,ylabel3,data1,data2,data3,t_string1,t_string2,t_string3);
                end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                try
                       data1=[t v(:,1)];
                catch       
                end
                try
                       data2=[t v(:,2)];
                catch       
                end
                try
                       data3=[t v(:,3)];
                catch       
                end
            
                if(app.iu<=2)
                    ylabel1='Velocity (in/sec)';
                else
                    ylabel1='Velocity (m/sec)';
                end               
                
                xlabel2='Time(sec)';
                xlabel3='Time(sec)';    
                    
                t_string1='Velocity dof 1';
                t_string2='Velocity dof 2';
                t_string3='Velocity dof 3';    
            
                if(num==2)
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel1,data1,data2,t_string1,t_string2);
                end
                if(num==3)
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel1,ylabel1,data1,data2,data3,t_string1,t_string2,t_string3);
                end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                try
                       data1=[t a(:,1)];
                catch       
                end
                try
                       data2=[t a(:,2)];
                catch       
                end
                try
                       data3=[t a(:,3)];
                catch       
                end
            
                ylabel1='Accel (G)';           
                
                xlabel1='Time (sec)';  
                    
                t_string1='Acceleration dof 1';
                t_string2='Acceleration dof 2';
                t_string3='Acceleration dof 3';    
            
                if(num==2)
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel,ylabel1,ylabel1,data1,data2,t_string1,t_string2);
                end
                if(num==3)
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel1,ylabel1,ylabel1,ylabel1,data1,data2,data3,t_string1,t_string2,t_string3);
                end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            disp(' ');
                    
                out1=sprintf('   Acceleration (G) ');

                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sa=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(a(:,i)),min(a(:,i)),std(a(:,i)));
                    disp(out3);    
                    sa=sprintf('%s\n%s',sa,out3);
                end 

                disp(' ');
                    
                if(app.iu==1)
                    out1=sprintf('   Velocity (in/sec) ');
                else
                    out1=sprintf('   Velocity (m/sec) ');                    
                end    
                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sv=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(v(:,i)),min(v(:,i)),std(v(:,i)));
                    disp(out3);    
                    sv=sprintf('%s\n%s',sv,out3);
                end 

            
                disp(' ');
                    
                if(app.iu==1)
                    out1=sprintf('       Displacement (in) ');
                    
                else
                    out1=sprintf('       Displacement (mm) ');        
                end    
                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sd=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(d(:,i)),min(d(:,i)),std(d(:,i)));
                    disp(out3);    
                    sd=sprintf('%s\n%s',sd,out3);
                end    
                
            app.displacement=[t d];
            app.velocity=[t v];
            app.acceleration=[t a];

            % app.AccelerationTextArea.Value=sq;

            ss=sprintf(' %7.3g\n',fn_orig(1));
            for i=2:length(fn_orig)
                ss=sprintf('%s %7.3g\n',ss,fn_orig(i));
            end    

            st=sprintf(' %7.3g\n',app.fn(1));
            for i=2:length(app.fn)
                st=sprintf('%s %7.3g\n',st,app.fn(i));
            end    

            sz=size(app.ModeShapes);

            for i=1:sz(2)
                for j=1:sz(1)
                    if(i==1 && j==1)
                        smode=sprintf('%6.3g',app.ModeShapes(i,j));
                    else
                        smode=sprintf('%s %6.3g',smode,app.ModeShapes(i,j));
                    end
                end
                smode=sprintf('%s\n',smode);
            end                

            app.AccelerationTextArea.Value=sa;
            app.VelocityTextArea.Value=sv;
            app.DisplacementTextArea.Value=sd;
            app.UnenforcedMotionSystemNaturalFrequenciesHzTextArea.Value=ss;
            app.EnforcedMotionSystemNaturalFrequenciesHzTextArea.Value=st;
            app.EnforcedMotionSystemModeShapesTextArea.Value=smode;

            app.TabGroup.SelectedTab=app.ViewResultsTab;

        end

        % Button pushed function: UpdateDisplacementListButton
        function UpdateDisplacementListButtonPushed(app, event)
            UpdateDisp(app);
        end

        % Value changed function: DampingMethodListBox
        function DampingMethodListBoxValueChanged(app, event)
           
            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));
            

            disp('**')

            h=app.DampingTabGroup.Children(app.idamp);

            app.DampingTabGroup.SelectedTab = h;

        end

        % Button pushed function: UpdateCListButton
        function UpdateCListButtonPushed(app, event)
            UpdateC(app);
        end

        % Button pushed function: ReadMassStiffnessButton
        function ReadMassStiffnessButtonPushed(app, event)
           try
                FS=app.MassArrayListBox.Value;
                app.M=evalin('base',FS);
                app.MM=app.M;
            catch
                warndlg('Mass array read failed');
                return;
            end

            try
                FS=app.StiffnessArrayListBox.Value;
                app.K=evalin('base',FS);
            catch
                warndlg('Stiffness array read failed');
                return;
            end

            sz=size(app.M);
            app.ndof=sz(1);

            ChangeDamping(app);

            msgbox('Arrays Read');

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)

            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            DQ=app.UITable.Data;


            if(contains(nD,'Uniform'))

                if(contains(nmn,'Amplification')) 
                    v=1/(2*DQ{1});
                else  
                    v=DQ{1};
                end   
                app.dm=ones(app.ndof,1)*v;
                
            else

                ddd=str2num(char(DQ));

                if(contains(nmn,'Amplification')) 

                    for i=1:length(DQ)
                        v=1/(2*ddd(i));
                        app.dm(i)=v;
                    end
                else  
                              
                    for i=1:length(DQ)
                        v=ddd(i);
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           
            Items = strtrim(app.FunctionListBox.Items);
            im = strtrim(app.FunctionListBox.Value);
            n = find(strcmp(Items, im));

            i=str2num(app.OutputdofNumberEditField.Value);

            if(n==1)
                data=[app.acceleration(:,1) app.acceleration(:,i+1)];
            end
            if(n==2)
                data=[app.velocity(:,1) app.velocity(:,i+1)];
            end
            if(n==3)
                data=[app.displacement(:,1) app.displacement(:,i+1)];
            end

            
            output_array=app.OutputArrayNameEditField.Value;
            assignin('base', output_array, data);
            
            h = msgbox('Save Complete'); 

        end

        % Value changed function: NumberofdofwithEnforcedMotionListBox
        function NumberofdofwithEnforcedMotionListBoxValueChanged(app, event)
            
            enforced_dof(app);
        end

        % Button pushed function: CalculateButton_response_2
        function CalculateButton_response_2Pushed(app, event)
            prep(app);

            try
                FS=app.AccelerationArrayListBox.Value;
                EA=evalin('base',FS); 
            catch
                warndlg('Acceleration array read failed');
                return;
            end
            
            if(isempty(app.M))
               warndlg('Read mass array'); 
               return; 
            end  
            if(isempty(app.K))
               warndlg('Read stiffness array'); 
               return; 
            end 
            
            if(app.iu==2)  % lbm
                app.MM=app.M/386;
            end

            %%%%
            
            enforced_dof=app.edof;

            for i=1:length(enforced_dof)
                if(enforced_dof(i)==0)
                    warndlg('Enforced Motion dof must be > 0')
                    return;
                end    
            end    

            num_ea=length(app.edof);
            
            szM=size(app.M);
            szEA=size(EA);
            
            NT=szEA(1);
            nt=NT;
            
            FFI=EA(:,2:szEA(2));
                
            if(app.iu==1)
                FFI=FFI*386;
            else
                FFI=FFI*9.81;
            end    
            
            app.ndof=szM(1);
            
            dur=EA(NT,1)-EA(1,1);
           
            dt=mean(diff(EA(:,1)));
            
            fprintf(' NT=%d  dur=%7.3g sec  dt=%7.3g sec \n',NT,dur,dt);

            szFF=size(FFI);

            dvelox=zeros(NT,szFF(2));
            ddisp=zeros(NT,szFF(2));

            for ijk=1:szFF(2)
                [dvelox(:,ijk)]=integrate_function(FFI(:,ijk),dt);
                [ddisp(:,ijk)]=integrate_function(dvelox(:,ijk),dt);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.K,app.MM,1);

            fn_orig=app.fn;
            
            Damping_Read(app);
            
            %%
            
            mass=app.MM;
            cdamping=app.C;
            stiff=app.K;
            
            cdamping
            
            accel_dof=zeros(app.ndof,1);
            
            for i=1:num_ea
                accel_dof(app.edof(i))=1;
            end

            num=length(accel_dof);
            
            forig=zeros(nt,num_ea);
            %
            k=1;
            for i=1:length(accel_dof)
                nfi=accel_dof(i);
                if(nfi>0)
                    forig(:,k)=FFI(:,nfi);
                    k=k+1;
                end    
            end
            
            j=1;
            k=1;

            for i=1:length(accel_dof)
                if(accel_dof(i)>0)
                    enforced_dof(k)=i;
                    k=k+1;
                else
                    app.free_dof(j)=i;
                    j=j+1;
                end
            end
            
            app.nem=length(enforced_dof);
            app.nfree=length(app.free_dof);
            
            dispm=1;
            
            
            [app.fn,~,app.ModeShapes,app.MST,app.Mwd,~,~,app.Mww,Cww,Kww,app.TT,T1,T2,app.KT,app.CT,app.MT]=...
               partition_matrices_mck(mass,cdamping,stiff,num,app.nfree,app.nem,app.free_dof,...
                                                           enforced_dof,'accel',dispm);
            %
            
            app.ngw=zeros(app.ndof,1);
            
            for i=1:app.nem
                app.ngw(i)=enforced_dof(i);
            end
            for i=1:app.nfree
                app.ngw(i+app.nem)=app.free_dof(i);
            end
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%
            
            clear r;
            clear part;
            clear ModalMass;
            %
            sz=size(app.Mww);
            %
            n_free_dof=sz(1);
            %
            app.num_modes=n_free_dof;
            %
            clear temp;
            temp=app.ModeShapes(:,1:app.num_modes);
            clear ModeShapes;
            app.ModeShapes=temp;
            app.MST=app.ModeShapes';
            %
            % r=ones(app.num_modes,1);
            %
            % part = MST*Mww*r;
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  displacement
            %
            %  d
            %  v
            %  acc
            %
            %%
            n=app.num_modes;
            %
            %%
            
            [a0,a1,a2,a3,a4,a5,a6,a7]=Newmark_coefficients(dt);
            %
            KH=Kww+a0*app.Mww+a1*Cww;
            %
            inv_KH=pinv(KH); 
            
            %
            t=zeros(nt,1);
            d=zeros(nt,app.nfree);
            v=zeros(nt,app.nfree);
            acc=zeros(nt,app.nfree);
            %
            U=zeros(n,1);
            Ud=zeros(n,1);
            Udd=zeros(n,1);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%
            
            progressbar;
            for i=1:(nt-1)
                progressbar(i/(nt-1));
            %   
                FP=-app.Mwd*(FFI(i,:))';
            %    
                FH=FP+app.Mww*(a0*U+a2*Ud+a3*Udd)+Cww*(a1*U+a4*Ud+a5*Udd);
            %
            %%    Un=KH\FH;
            %
                Un=inv_KH*FH;
            %
                Uddn=a0*(Un-U)-a2*Ud-a3*Udd;
                Udn=Ud+a6*Udd+a7*Uddn;
                U=Un;
                Ud=Udn;
                Udd=Uddn;
            %
                d(i+1,:)=U';
                v(i+1,:)=Ud';
                acc(i+1,:)=Udd';
                t(i+1)=i*dt;   
            %
            end
            progressbar(1);
               
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    Transformation back to xd xf
            %
            %    dT=TT*d';
            %    vT=TT*v';
            %%    accT=TT*acc';
            %
             dT=(T1*ddisp' + T2*d')';
             vT=(T1*dvelox' + T2*v')';
            accT=(T1*FFI' + T2*acc')';
            %
            %  Put in original order
            %
              ZdT=[ ddisp  dT ];
              ZvT=[ dvelox  vT ];
            ZaccT=[ forig  accT ];
            %
            clear d;   
            clear v;
            clear acc;
            
            d=zeros(nt,num);
            v=zeros(nt,num);
            acc=zeros(nt,num);
            
            %
            for i=1:num
               for j=1:nt
                    d(j,app.ngw(i))=  (ZdT(j,i));        
                    v(j,app.ngw(i))=  (ZvT(j,i));     
                  acc(j,app.ngw(i))=(ZaccT(j,i));
               end
            end

            d=real(d);
            v=real(v);
            acc=real(acc);
        
            %%% leave after mdof_plot
            
            clear acceleration;
            clear velocity;
            clear displacement;
            %
            a=acc;
            
            % [v]=integrate_function(a,dt);
            % [d]=integrate_function(v,dt);
            
            if(app.iu<=2)
                acc=acc/386;
            else
                acc=acc/9.81;
                d=d/1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [colororder,color_rows]=line_colors();
            
            %%
                hold('all');
            %

                j=1;
                for i = 1:num
                    if(j>color_rows)
                        j=j-color_rows;
                    end
                    out1=sprintf('dof %d',i);
                    plot(t,acc(:,i),'Color', colororder(j,:),'DisplayName',out1);
                    legend('-DynamicLegend');
                    j=j+1;
                end
                hold off;
            %%
                xlabel('Time(sec)');
                title('Acceleration');
                
                ylabel('Accel(G)');
               
                grid on;
                hold off;
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
               
                fig_num=2;
            
                try
                       data1=[t acc(:,1)];
                catch
                end
                try
                       data2=[t acc(:,2)];
                catch
                end
                try
                       data3=[t acc(:,3)];
                catch
                end
            
                xlabel2='Time(sec)';
                xlabel3='Time(sec)';    
                
                ylabel1='Accel(G)';
                ylabel2='Accel(G)';
                ylabel3='Accel(G)';   
                
                t_string1='Acceleration dof 1';
                t_string2='Acceleration dof 2';
                t_string3='Acceleration dof 3';    
            
                
                if(num==2)
                    
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
                    
                end
                if(num==3)
            
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel2,ylabel3,data1,data2,data3,t_string1,t_string2,t_string3);
                    
                end
                    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                try
                       data1=[t v(:,1)];
                catch       
                end
                try
                       data2=[t v(:,2)];
                catch       
                end
                try
                       data3=[t v(:,3)];
                catch       
                end
            
                if(app.iu<=2)
                    ylabel1='Velocity (in/sec)';
                else
                    ylabel1='Velocity (m/sec)';
                end               
                
                xlabel2='Time(sec)';
                xlabel3='Time(sec)';    
                    
                t_string1='Velocity dof 1';
                t_string2='Velocity dof 2';
                t_string3='Velocity dof 3';    
            
                if(num==2)
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel1,data1,data2,t_string1,t_string2);
                end
                if(num==3)
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel1,ylabel1,data1,data2,data3,t_string1,t_string2,t_string3);
                end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                try
                       data1=[t d(:,1)];
                catch       
                end
                try
                       data2=[t d(:,2)];
                catch       
                end
                try
                       data3=[t d(:,3)];
                catch       
                end
            
                if(app.iu<=2)
                    ylabel1='Displacement (in)';
                else
                    ylabel1='Displacement (mm)';
                end               
                
                xlabel2='Time(sec)';
                xlabel3='Time(sec)';    
                    
                t_string1='Displacement dof 1';
                t_string2='Displacement dof 2';
                t_string3='Displacement dof 3';    
            
                if(num==2)
                    [fig_num]=subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel1,data1,data2,t_string1,t_string2);
                end
                if(num==3)
                    [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel1,ylabel1,data1,data2,data3,t_string1,t_string2,t_string3);
                end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                disp(' ');
                    
                out1=sprintf('   Acceleration (G) ');

                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sa=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(acc(:,i)),min(acc(:,i)),std(acc(:,i)));
                    disp(out3);    
                    sa=sprintf('%s\n%s',sa,out3);
                end 

                disp(' ');
                    
                if(app.iu==1)
                    out1=sprintf('   Velocity (in/sec) ');
                else
                    out1=sprintf('   Velocity (m/sec) ');                    
                end    
                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sv=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(v(:,i)),min(v(:,i)),std(v(:,i)));
                    disp(out3);    
                    sv=sprintf('%s\n%s',sv,out3);
                end 

                disp(' ');
                    
                if(app.iu==1)
                    out1=sprintf('   Displacement (in) ');
                else
                    out1=sprintf('   Displacement (mm/sec) ');                    
                end    
                disp(out1);

                out2=sprintf(' dof    max       min      std dev ');
                disp(out2);

                sd=sprintf('%s\n%s\n',out1,out2);

                for i = 1:num
                    out3=sprintf(' %d  %7.3g  %7.3g  %7.3g ',i,max(d(:,i)),min(d(:,i)),std(d(:,i)));
                    disp(out3);    
                    sd=sprintf('%s\n%s',sd,out3);
                end 
 

            %
            app.displacement=[t d];
            app.velocity=[t v];
            app.acceleration=[t a];

            % app.AccelerationTextArea.Value=sq;

            ss=sprintf(' %7.3g\n',fn_orig(1));
            for i=2:length(fn_orig)
                ss=sprintf('%s %7.3g\n',ss,fn_orig(i));
            end    

            st=sprintf(' %7.3g\n',app.fn(1));
            for i=2:length(app.fn)
                st=sprintf('%s %7.3g\n',st,app.fn(i));
            end    


            sz=size(app.ModeShapes);

            for i=1:sz(2)
                for j=1:sz(1)
                    if(i==1 && j==1)
                        smode=sprintf('%6.3g',app.ModeShapes(i,j));
                    else
                        smode=sprintf('%s %6.3g',smode,app.ModeShapes(i,j));
                    end
                end
                smode=sprintf('%s\n',smode);
            end    

            app.AccelerationTextArea.Value=sa;
            app.VelocityTextArea.Value=sv;
            app.DisplacementTextArea.Value=sd;

            app.UnenforcedMotionSystemNaturalFrequenciesHzTextArea.Value=ss;
            app.EnforcedMotionSystemNaturalFrequenciesHzTextArea.Value=st;
            app.EnforcedMotionSystemModeShapesTextArea.Value=smode;


            app.TabGroup.SelectedTab=app.ViewResultsTab;

        end

        % Button pushed function: UpdateAccelerationListButton
        function UpdateAccelerationListButtonPushed(app, event)
            UpdateDisp(app);  % does both disp & accel
        end

        % Selection change function: DampingTabGroup
        function DampingTabGroupSelectionChanged(app, event)

        end

        % Button down function: DampingTabGroup
        function DampingTabGroupButtonDown(app, event)

        end

        % Button down function: CarrayTab
        function CarrayTabButtonDown(app, event)
            app.DampingMethodListBox.Value=app.DampingMethodListBox.Items(1);
            ChangeDamping(app);
        end

        % Button down function: ModalTab
        function ModalTabButtonDown(app, event)
            app.DampingMethodListBox.Value=app.DampingMethodListBox.Items(2);
            ChangeDamping(app);
        end

        % Size changed function: EnforcedAccelerationTab
        function EnforcedAccelerationTabSizeChanged(app, event)
            position = app.EnforcedAccelerationTab.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nm_mdof_arbitrary_enforced_motionUIFigure = uifigure('Visible', 'off');
            app.nm_mdof_arbitrary_enforced_motionUIFigure.Name = 'nm_mdof_arbitrary_enforced_motion';
            app.nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel = uilabel(app.nm_mdof_arbitrary_enforced_motionUIFigure);
            app.nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel.Text = {'nm_mdof_arbitrary_enforced_motion_a   ver 1.0  by Tom Irvine    '; ''; 'The numerical engine is the Newmark-beta method, alpha=0.25  beta=0.5.    This script is intended for smaller systems with maybe <= 30 dof.    '};
            app.ReturnButton = uibutton(app.nm_mdof_arbitrary_enforced_motionUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.nm_mdof_arbitrary_enforced_motionUIFigure);
            app.MassStiffnessTab = uitab(app.TabGroup);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.ForceMassDampingStiffnessUnitsListBoxLabel = uilabel(app.MassStiffnessTab);
            app.ForceMassDampingStiffnessUnitsListBoxLabel.Text = 'Force, Mass, Damping & Stiffness Units';
            app.UnitsListBox = uilistbox(app.MassStiffnessTab);
            app.UnitsListBox.Items = {'lbf, lbf sec^2/in, lbf sec/in, lbf/in', 'lbf, lbm, lbf sec/in, lbf/in         ', 'N, kg, N s/m, N/m'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'lbf, lbf sec^2/in, lbf sec/in, lbf/in';
            app.UpdateListsButton = uibutton(app.MassStiffnessTab, 'push');
            app.UpdateListsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListsButtonPushed, true);
            app.UpdateListsButton.Text = 'Update Lists';
            app.ReadMassStiffnessButton = uibutton(app.MassStiffnessTab, 'push');
            app.ReadMassStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @ReadMassStiffnessButtonPushed, true);
            app.ReadMassStiffnessButton.Text = 'Read Mass & Stiffness';
            app.MassArrayListBoxLabel = uilabel(app.MassStiffnessTab);
            app.MassArrayListBoxLabel.Text = 'Mass Array';
            app.MassArrayListBox = uilistbox(app.MassStiffnessTab);
            app.MassArrayListBox.Items = {''};
            app.MassArrayListBox.Value = '';
            app.StiffnessArrayListBox = uilistbox(app.MassStiffnessTab);
            app.StiffnessArrayListBox.Items = {''};
            app.StiffnessArrayListBox.Value = '';
            app.StiffnessArrayListBoxLabel = uilabel(app.MassStiffnessTab);
            app.StiffnessArrayListBoxLabel.Text = 'Stiffness Array';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTabGroup = uitabgroup(app.DampingTab);
            app.DampingTabGroup.SelectionChangedFcn = createCallbackFcn(app, @DampingTabGroupSelectionChanged, true);
            app.DampingTabGroup.ButtonDownFcn = createCallbackFcn(app, @DampingTabGroupButtonDown, true);
            app.CarrayTab = uitab(app.DampingTabGroup);
            app.CarrayTab.Title = 'C array';
            app.CarrayTab.ButtonDownFcn = createCallbackFcn(app, @CarrayTabButtonDown, true);
            app.UpdateCListButton = uibutton(app.CarrayTab, 'push');
            app.UpdateCListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCListButtonPushed, true);
            app.UpdateCListButton.Text = 'Update C List';
            app.DampingCoefficientArrayListBoxLabel = uilabel(app.CarrayTab);
            app.DampingCoefficientArrayListBoxLabel.Text = 'Damping Coefficient Array';
            app.DampingCoefficientArrayListBox = uilistbox(app.CarrayTab);
            app.DampingCoefficientArrayListBox.Items = {''};
            app.DampingCoefficientArrayListBox.Value = '';
            app.ModalTab = uitab(app.DampingTabGroup);
            app.ModalTab.Title = 'Modal';
            app.ModalTab.ButtonDownFcn = createCallbackFcn(app, @ModalTabButtonDown, true);
            app.UITable = uitable(app.ModalTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.ModalTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.ModalTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.ModalTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.ModalTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.ModalTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.DampingMethodListBoxLabel = uilabel(app.DampingTab);
            app.DampingMethodListBoxLabel.Text = 'Damping Method';
            app.DampingMethodListBox = uilistbox(app.DampingTab);
            app.DampingMethodListBox.Items = {'C array      ', 'Modal'};
            app.DampingMethodListBox.ValueChangedFcn = createCallbackFcn(app, @DampingMethodListBoxValueChanged, true);
            app.DampingMethodListBox.Value = 'C array      ';
            app.EnforcedMotiondofTab = uitab(app.TabGroup);
            app.EnforcedMotiondofTab.Title = 'Enforced Motion dof';
            app.NumberofdofwithEnforcedMotionLabel = uilabel(app.EnforcedMotiondofTab);
            app.NumberofdofwithEnforcedMotionLabel.Text = {'Number of dof with '; 'Enforced Motion'};
            app.NumberofdofwithEnforcedMotionListBox = uilistbox(app.EnforcedMotiondofTab);
            app.NumberofdofwithEnforcedMotionListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofdofwithEnforcedMotionListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofdofwithEnforcedMotionListBoxValueChanged, true);
            app.NumberofdofwithEnforcedMotionListBox.Value = '1';
            app.UITable_edof = uitable(app.EnforcedMotiondofTab);
            app.UITable_edof.ColumnName = {'dof Number'};
            app.UITable_edof.RowName = {};
            app.UITable_edof.ColumnEditable = true;
            app.EnforceddofNumberbyArrayColumnLabel = uilabel(app.EnforcedMotiondofTab);
            app.EnforceddofNumberbyArrayColumnLabel.Text = {'Enforced dof Number '; 'by Array Column'};
            app.EnforcedDisplacementTab = uitab(app.TabGroup);
            app.EnforcedDisplacementTab.Title = 'Enforced Displacement';
            app.TheDispLabel = uilabel(app.EnforcedDisplacementTab);
            app.TheDispLabel.Text = 'The displacement array must have two columns:  time (sec) & displacement (in)';
            app.DisplacementArrayListBoxLabel = uilabel(app.EnforcedDisplacementTab);
            app.DisplacementArrayListBoxLabel.Text = 'Displacement Array';
            app.DisplacementArrayListBox = uilistbox(app.EnforcedDisplacementTab);
            app.DisplacementArrayListBox.Items = {''};
            app.DisplacementArrayListBox.Value = '';
            app.UpdateDisplacementListButton = uibutton(app.EnforcedDisplacementTab, 'push');
            app.UpdateDisplacementListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplacementListButtonPushed, true);
            app.UpdateDisplacementListButton.Text = 'Update Displacement List';
            app.CalculateButton_response = uibutton(app.EnforcedDisplacementTab, 'push');
            app.CalculateButton_response.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_responsePushed, true);
            app.CalculateButton_response.Text = 'Calculate';
            app.EnforcedAccelerationTab = uitab(app.TabGroup);
            app.EnforcedAccelerationTab.SizeChangedFcn = createCallbackFcn(app, @EnforcedAccelerationTabSizeChanged, true);
            app.EnforcedAccelerationTab.Title = 'Enforced Acceleration';
            app.CalculateButton_response_2 = uibutton(app.EnforcedAccelerationTab, 'push');
            app.CalculateButton_response_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_response_2Pushed, true);
            app.CalculateButton_response_2.Text = 'Calculate';
            app.UpdateAccelerationListButton = uibutton(app.EnforcedAccelerationTab, 'push');
            app.UpdateAccelerationListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAccelerationListButtonPushed, true);
            app.UpdateAccelerationListButton.Text = 'Update Acceleration List';
            app.AccelerationArrayListBoxLabel = uilabel(app.EnforcedAccelerationTab);
            app.AccelerationArrayListBoxLabel.Text = 'Acceleration Array';
            app.AccelerationArrayListBox = uilistbox(app.EnforcedAccelerationTab);
            app.AccelerationArrayListBox.Items = {''};
            app.AccelerationArrayListBox.Value = '';
            app.TheAccelLabel_2 = uilabel(app.EnforcedAccelerationTab);
            app.TheAccelLabel_2.Text = 'The acceleration array must have two columns:  time (sec) & acceleration (G)';
            app.ViewResultsTab = uitab(app.TabGroup);
            app.ViewResultsTab.Title = 'View Results';
            app.TabGroup2 = uitabgroup(app.ViewResultsTab);
            app.ModalTab_3 = uitab(app.TabGroup2);
            app.ModalTab_3.Title = 'Modal';
            app.EnforcedMotionSystemNaturalFrequenciesHzTextAreaLabel = uilabel(app.ModalTab_3);
            app.EnforcedMotionSystemNaturalFrequenciesHzTextAreaLabel.Text = {'Enforced Motion System'; 'Natural Frequencies (Hz)'};
            app.EnforcedMotionSystemNaturalFrequenciesHzTextArea = uitextarea(app.ModalTab_3);
            app.EnforcedMotionSystemNaturalFrequenciesHzTextArea.Editable = 'off';
            app.NaturalFrequenicesHzReadOnlyLabel = uilabel(app.ModalTab_3);
            app.NaturalFrequenicesHzReadOnlyLabel.Text = {'Unenforced Motion System '; 'Natural Frequencies (Hz)'};
            app.UnenforcedMotionSystemNaturalFrequenciesHzTextArea = uitextarea(app.ModalTab_3);
            app.UnenforcedMotionSystemNaturalFrequenciesHzTextArea.Editable = 'off';
            app.EnforcedMotionSystemModeShapesTextAreaLabel = uilabel(app.ModalTab_3);
            app.EnforcedMotionSystemModeShapesTextAreaLabel.Text = {'Enforced Motion System'; 'Mode Shapes'};
            app.EnforcedMotionSystemModeShapesTextArea = uitextarea(app.ModalTab_3);
            app.EnforcedMotionSystemModeShapesTextArea.Editable = 'off';
            app.ResponseTab = uitab(app.TabGroup2);
            app.ResponseTab.Title = 'Response';
            app.AccelerationTextAreaLabel = uilabel(app.ResponseTab);
            app.AccelerationTextAreaLabel.Text = 'Acceleration';
            app.AccelerationTextArea = uitextarea(app.ResponseTab);
            app.AccelerationTextArea.Editable = 'off';
            app.VelocityTextAreaLabel = uilabel(app.ResponseTab);
            app.VelocityTextAreaLabel.Text = 'Velocity';
            app.VelocityTextArea = uitextarea(app.ResponseTab);
            app.VelocityTextArea.Editable = 'off';
            app.DisplacementTextArea_2Label = uilabel(app.ResponseTab);
            app.DisplacementTextArea_2Label.Text = 'Displacement';
            app.DisplacementTextArea = uitextarea(app.ResponseTab);
            app.DisplacementTextArea.Editable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.FunctionListBoxLabel = uilabel(app.ExportTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTab);
            app.FunctionListBox.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.FunctionListBox.Value = 'Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputdofNumberLabel = uilabel(app.ExportTab);
            app.OutputdofNumberLabel.Text = 'Output dof Number';
            app.OutputdofNumberEditField = uieditfield(app.ExportTab, 'text');
            app.OutputdofNumberEditField.Value = '1';
            % Show the figure after all components are created
            app.nm_mdof_arbitrary_enforced_motionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nm_mdof_arbitrary_enforced_motion_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.nm_mdof_arbitrary_enforced_motionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.nm_mdof_arbitrary_enforced_motionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nm_mdof_arbitrary_enforced_motionUIFigure)
        end
    end
end