classdef nm_mdof_arbitrary_single_force_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nm_mdof_forceUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MassStiffnessTab                matlab.ui.container.Tab
        StiffnessArrayListBoxLabel      matlab.ui.control.Label
        StiffnessArrayListBox           matlab.ui.control.ListBox
        MassArrayListBox                matlab.ui.control.ListBox
        MassArrayListBoxLabel           matlab.ui.control.Label
        ReadMassStiffnessButton         matlab.ui.control.Button
        UpdateListsButton               matlab.ui.control.Button
        UnitsListBox                    matlab.ui.control.ListBox
        ForceMassDampingStiffnessUnitsListBoxLabel  matlab.ui.control.Label
        DampingTab                      matlab.ui.container.Tab
        DampingMethodListBox            matlab.ui.control.ListBox
        DampingMethodListBoxLabel       matlab.ui.control.Label
        DampingTabGroup                 matlab.ui.container.TabGroup
        CarrayTab                       matlab.ui.container.Tab
        DampingCoefficientArrayListBox  matlab.ui.control.ListBox
        DampingCoefficientArrayListBoxLabel  matlab.ui.control.Label
        UpdateCListButton               matlab.ui.control.Button
        ModalTab                        matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AppliedForceTab                 matlab.ui.container.Tab
        UpdateForceListButton           matlab.ui.control.Button
        ForceArrayListBox               matlab.ui.control.ListBox
        ForceArrayListBoxLabel          matlab.ui.control.Label
        TheforcearraymusthavetwocolumnstimesecforceLabel  matlab.ui.control.Label
        AppliedForcedofEditField        matlab.ui.control.EditField
        AppliedForcedofEditFieldLabel   matlab.ui.control.Label
        CalculationTab                  matlab.ui.container.Tab
        NaturalFrequenicesHzReadOnlyTextArea  matlab.ui.control.TextArea
        NaturalFrequenicesHzReadOnlyLabel  matlab.ui.control.Label
        ResultsReadOnlyTextArea         matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel    matlab.ui.control.Label
        CalculateButton_response        matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        OutputdofNumberEditField        matlab.ui.control.EditField
        OutputdofNumberLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iu                  % Description
        force_acceleration
        acceleration
        velocity
        displacement
        idamp 
        M
        K
        ndof
        dm
    end
    
    methods (Access = private)

        function UpdateC(app)
            [cell_array]=update_2D_array();

            try
                app.DampingCoefficientArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.DampingCoefficientArrayListBox.Items);
                app.DampingCoefficientArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.DampingCoefficientArrayListBox.Value=app.DampingCoefficientArrayListBox.Items{1};
        end          

        function UpdateForce(app)
            [cell_array]=update_time_history();

            try
                app.ForceArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.ForceArrayListBox.Items);
                app.ForceArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.ForceArrayListBox.Value=app.ForceArrayListBox.Items{1};
        end    

        function UpdateMK(app)
            [cell_array]=update_2D_array();

            try
                app.MassArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.MassInputArrayListBox.Items);
                app.MassInputArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.MassArrayListBox.Value=app.MassArrayListBox.Items{1};

            %%

            try
                app.StiffnessArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.StiffnessInputArrayListBox.Items);
                app.StiffnessArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.StiffnessArrayListBox.Value=app.StiffnessArrayListBox.Items{1};

        end

        function Units(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

      function ChangeDamping(app)

            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));

            if(isempty(app.M))
                warndlg('Read Mass array first');
                return;
            end    

            try
                sz=size(app.M);
                app.ndof=sz(1);
            catch
                app.ndof=1;
            end

            if(app.ndof==0)
                app.ndof=1;
            end    

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else

                for i=1:app.ndof
                    ssr{i}=sprintf('Mode %d',i);
                    ssq{i}='10';
                    ssd{i}='0.05';
                end    

                app.UITable.RowName=ssr';
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData=ssq';
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData=ssd';                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style

            ClosePlots(app);
            Units(app);
            UpdateForce(app);
            UpdateMK(app);
            UpdateC(app);

          
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
           
            Units(app);
        end

        % Button pushed function: UpdateListsButton
        function UpdateListsButtonPushed(app, event)
            UpdateMK(app);
        end

        % Button pushed function: CalculateButton_response
        function CalculateButton_responsePushed(app, event)

            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));

            ClosePlots(app);

            try
                FS=app.ForceArrayListBox.Value;
                F=evalin('base',FS); 
            catch
                warndlg('Force array read failed');
                return;
            end
            
            if(isempty(app.M))
               warndlg('Read mass array'); 
               return; 
            end  
            if(isempty(app.K))
               warndlg('Read stiffness array'); 
               return; 
            end 
            if(isempty(F))
               warndlg('enter force array'); 
               return; 
            end 
            
            szF=size(F);
            
            NT=szF(1);
            
            force_dof=str2num(app.AppliedForcedofEditField.Value);
            
            if(isempty(force_dof))
              warndlg('enter force dof');  
              return;  
            end

            if(force_dof>app.ndof)
               warndlg(' force_dof>ndof  ');
               return; 
            end
            
            dur=F(NT,1)-F(1,1);
            
            dt=mean(diff(F(:,1)));
            
            fprintf(' NT=%d  dur=%8.4g sec  dt=%8.4g sec \n',NT,dur,dt);
 
            %
            disp(' ');
            [fn,omegan,ModeShapes,MST]=Generalized_Eigen(app.K,app.M,1);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            FF=zeros(NT,app.ndof);
            FF(:,force_dof)=F(:,2);
            
            t=F(:,1);
            force=F(:,2);
            
            %%
            
            ndamp=app.idamp;
            
            if(ndamp==1)
            
                FS=app.DampingCoefficientArrayListBox.Value;
                C=evalin('base',FS);     
            
                if(isempty(C))
                    warndlg('enter damping coefficient'); 
                    return; 
                end    
                
            else
                
                if(isempty(app.dm))
                    warndlg('Enter damping first');
                    return;
                end    
                   
                dampv=app.dm;
            
                C=zeros(app.ndof,app.ndof);
            
                for i=1:app.ndof
                    C(i,i)=2*dampv(i)*omegan(i);
                end
            
                C=app.M*ModeShapes*C*MST*app.M;   
               
            end
            
            DI=zeros(app.ndof,1);
            VI=zeros(app.ndof,1);
            
            
            [U,Ud,Udd]=Newmark_force_mdof(DI,VI,dt,NT,app.ndof,app.M,C,app.K,FF);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            x=U';
            y=Ud';
            accel=Udd';
            v=y;
            
            mdof_plot_legend_3iu(t,x,v,accel,app.ndof,app.iu);
            
            %%% leave after mdof_plot
            
            if(app.iu<=2)
                accel=accel/386;
            end
            %
            app.acceleration=[t accel];
            app.velocity=[t v];
            app.displacement=[t x];
            app.force_acceleration=[ t force accel ];

            for i=1:length(fn)
                if(i==1)
                    ss=sprintf(' %d.  %7.4g\n',i,fn(i));
                else
                    ss=sprintf('%s %d.  %7.4g\n',ss,i,fn(i));                   
                end    
            end    

            if(app.iu<=2)
                sq=sprintf(' Displacements (in) \n  dof     max        min \n\n');
            else
                sq=sprintf(' Displacements (m) \n  dof     max        min \n\n');
            end    

            for i=1:app.ndof
                sq=sprintf('%s   %d     %6.2e    %6.2e \n',sq,i,max(x(:,i)),min(x(:,i)));
            end

            app.ResultsReadOnlyTextArea.Value=sq;

            app.NaturalFrequenicesHzReadOnlyTextArea.Value=ss;
            
        end

        % Button pushed function: UpdateForceListButton
        function UpdateForceListButtonPushed(app, event)
            UpdateForce(app);
        end

        % Value changed function: DampingMethodListBox
        function DampingMethodListBoxValueChanged(app, event)
           
            Items = strtrim(app.DampingMethodListBox.Items);
            im = strtrim(app.DampingMethodListBox.Value);
            app.idamp = find(strcmp(Items, im));
            

            disp('**')

            h=app.DampingTabGroup.Children(app.idamp);

            app.DampingTabGroup.SelectedTab = h;

        end

        % Button pushed function: UpdateCListButton
        function UpdateCListButtonPushed(app, event)
            UpdateC(app);
        end

        % Button pushed function: ReadMassStiffnessButton
        function ReadMassStiffnessButtonPushed(app, event)
           try
                FS=app.MassArrayListBox.Value;
                app.M=evalin('base',FS);
            catch
                warndlg('Mass array read failed');
                return;
            end

            try
                FS=app.StiffnessArrayListBox.Value;
                app.K=evalin('base',FS);
            catch
                warndlg('Stiffness array read failed');
                return;
            end

            sz=size(app.M);
            app.ndof=sz(1);

            ChangeDamping(app);

            msgbox('Arrays Read');

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)

            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            DQ=app.UITable.Data;


            if(contains(nD,'Uniform'))

                if(contains(nmn,'Amplification')) 
                    v=1/(2*DQ{1});
                else  
                    v=DQ{1};
                end   
                app.dm=ones(app.ndof,1)*v;
                
            else

                ddd=str2num(char(DQ));

                if(contains(nmn,'Amplification')) 

                    for i=1:length(DQ)
                        v=1/(2*ddd(i));
                        app.dm(i)=v;
                    end
                else  
                              
                    for i=1:length(DQ)
                        v=ddd(i);
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           
            Items = strtrim(app.FunctionListBox.Items);
            im = strtrim(app.FunctionListBox.Value);
            n = find(strcmp(Items, im));

            i=str2num(app.OutputdofNumberEditField.Value);

            if(n==1)
                data=[app.acceleration(:,1) app.acceleration(:,i+1)];
            end
            if(n==2)
                data=[app.velocity(:,1) app.velocity(:,i+1)];
            end
            if(n==3)
                data=[app.displacement(:,1) app.displacement(:,i+1)];
            end
            if(n==4)
                data=[app.force_acceleration(:,1) app.force_acceleration(:,2) app.force_acceleration(:,i+2)];
            end
            
            output_array=app.OutputArrayNameEditField.Value;
            assignin('base', output_array, data);
            
            h = msgbox('Save Complete'); 

        end

        % Button down function: CarrayTab
        function CarrayTabButtonDown(app, event)

            
            app.DampingMethodListBox.Value=app.DampingMethodListBox.Items(1);
            ChangeDamping(app);
        end

        % Size changed function: ModalTab
        function ModalTabSizeChanged(app, event)
           
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)


        end

        % Button down function: DampingTab
        function DampingTabButtonDown(app, event)
    
        end

        % Selection change function: DampingTabGroup
        function DampingTabGroupSelectionChanged(app, event)
  
        end

        % Button down function: ModalTab
        function ModalTabButtonDown(app, event)
             app.DampingMethodListBox.Value=app.DampingMethodListBox.Items(2);
             ChangeDamping(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nm_mdof_forceUIFigure = uifigure('Visible', 'off');
            app.nm_mdof_forceUIFigure.Name = 'nm_mdof_force';
            app.nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel = uilabel(app.nm_mdof_forceUIFigure);
            app.nm_mdof_arbitrary_single_force_aver10byTomIrvineLabel.Text = {'nm_mdof_arbitrary_single_force_a   ver 1.0  by Tom Irvine    '; ''; 'The numerical engine is the Newmark-beta method, alpha=0.25  beta=0.5.    This script is intended for smaller systems with maybe <= 30 dof.    '};
            app.ReturnButton = uibutton(app.nm_mdof_forceUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.nm_mdof_forceUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MassStiffnessTab = uitab(app.TabGroup);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.ForceMassDampingStiffnessUnitsListBoxLabel = uilabel(app.MassStiffnessTab);
            app.ForceMassDampingStiffnessUnitsListBoxLabel.Text = 'Force, Mass, Damping & Stiffness Units';
            app.UnitsListBox = uilistbox(app.MassStiffnessTab);
            app.UnitsListBox.Items = {'lbf, lbf sec^2/in, lbf sec/in, lbf/in', 'lbf, lbm, lbf sec/in, lbf/in         ', 'N, kg, N s/m, N/m'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'lbf, lbf sec^2/in, lbf sec/in, lbf/in';
            app.UpdateListsButton = uibutton(app.MassStiffnessTab, 'push');
            app.UpdateListsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListsButtonPushed, true);
            app.UpdateListsButton.Text = 'Update Lists';
            app.ReadMassStiffnessButton = uibutton(app.MassStiffnessTab, 'push');
            app.ReadMassStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @ReadMassStiffnessButtonPushed, true);
            app.ReadMassStiffnessButton.Text = 'Read Mass & Stiffness';
            app.MassArrayListBoxLabel = uilabel(app.MassStiffnessTab);
            app.MassArrayListBoxLabel.Text = 'Mass Array';
            app.MassArrayListBox = uilistbox(app.MassStiffnessTab);
            app.MassArrayListBox.Items = {''};
            app.MassArrayListBox.Value = '';
            app.StiffnessArrayListBox = uilistbox(app.MassStiffnessTab);
            app.StiffnessArrayListBox.Items = {''};
            app.StiffnessArrayListBox.Value = '';
            app.StiffnessArrayListBoxLabel = uilabel(app.MassStiffnessTab);
            app.StiffnessArrayListBoxLabel.Text = 'Stiffness Array';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.DampingTab.ButtonDownFcn = createCallbackFcn(app, @DampingTabButtonDown, true);
            app.DampingTabGroup = uitabgroup(app.DampingTab);
            app.DampingTabGroup.SelectionChangedFcn = createCallbackFcn(app, @DampingTabGroupSelectionChanged, true);
            app.CarrayTab = uitab(app.DampingTabGroup);
            app.CarrayTab.Title = 'C array';
            app.CarrayTab.ButtonDownFcn = createCallbackFcn(app, @CarrayTabButtonDown, true);
            app.UpdateCListButton = uibutton(app.CarrayTab, 'push');
            app.UpdateCListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCListButtonPushed, true);
            app.UpdateCListButton.Text = 'Update C List';
            app.DampingCoefficientArrayListBoxLabel = uilabel(app.CarrayTab);
            app.DampingCoefficientArrayListBoxLabel.Text = 'Damping Coefficient Array';
            app.DampingCoefficientArrayListBox = uilistbox(app.CarrayTab);
            app.DampingCoefficientArrayListBox.Items = {''};
            app.DampingCoefficientArrayListBox.Value = '';
            app.ModalTab = uitab(app.DampingTabGroup);
            app.ModalTab.SizeChangedFcn = createCallbackFcn(app, @ModalTabSizeChanged, true);
            app.ModalTab.Title = 'Modal';
            app.ModalTab.ButtonDownFcn = createCallbackFcn(app, @ModalTabButtonDown, true);
            app.UITable = uitable(app.ModalTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.ModalTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.ModalTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.ModalTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.ModalTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.ModalTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.DampingMethodListBoxLabel = uilabel(app.DampingTab);
            app.DampingMethodListBoxLabel.Text = 'Damping Method';
            app.DampingMethodListBox = uilistbox(app.DampingTab);
            app.DampingMethodListBox.Items = {'C array      ', 'Modal'};
            app.DampingMethodListBox.ValueChangedFcn = createCallbackFcn(app, @DampingMethodListBoxValueChanged, true);
            app.DampingMethodListBox.Value = 'C array      ';
            app.AppliedForceTab = uitab(app.TabGroup);
            app.AppliedForceTab.Title = 'Applied Force';
            app.AppliedForcedofEditFieldLabel = uilabel(app.AppliedForceTab);
            app.AppliedForcedofEditFieldLabel.Text = 'Applied Force dof';
            app.AppliedForcedofEditField = uieditfield(app.AppliedForceTab, 'text');
            app.AppliedForcedofEditField.Value = '1';
            app.TheforcearraymusthavetwocolumnstimesecforceLabel = uilabel(app.AppliedForceTab);
            app.TheforcearraymusthavetwocolumnstimesecforceLabel.Text = 'The force array must have two columns:  time(sec) & force';
            app.ForceArrayListBoxLabel = uilabel(app.AppliedForceTab);
            app.ForceArrayListBoxLabel.Text = 'Force Array';
            app.ForceArrayListBox = uilistbox(app.AppliedForceTab);
            app.ForceArrayListBox.Items = {''};
            app.ForceArrayListBox.Value = '';
            app.UpdateForceListButton = uibutton(app.AppliedForceTab, 'push');
            app.UpdateForceListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateForceListButtonPushed, true);
            app.UpdateForceListButton.Text = 'Update Force List';
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.CalculateButton_response = uibutton(app.CalculationTab, 'push');
            app.CalculateButton_response.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_responsePushed, true);
            app.CalculateButton_response.Text = 'Calculate';
            app.ResultsReadOnlyTextAreaLabel = uilabel(app.CalculationTab);
            app.ResultsReadOnlyTextAreaLabel.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea = uitextarea(app.CalculationTab);
            app.ResultsReadOnlyTextArea.Editable = 'off';
            app.NaturalFrequenicesHzReadOnlyLabel = uilabel(app.CalculationTab);
            app.NaturalFrequenicesHzReadOnlyLabel.Text = {'Natural Frequenices (Hz)'; 'Read Only'};
            app.NaturalFrequenicesHzReadOnlyTextArea = uitextarea(app.CalculationTab);
            app.NaturalFrequenicesHzReadOnlyTextArea.Editable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.FunctionListBoxLabel = uilabel(app.ExportTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTab);
            app.FunctionListBox.Items = {'Acceleration', 'Velocity    ', 'Displacement', 'Force & Acceleration'};
            app.FunctionListBox.Value = 'Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputdofNumberLabel = uilabel(app.ExportTab);
            app.OutputdofNumberLabel.Text = 'Output dof Number';
            app.OutputdofNumberEditField = uieditfield(app.ExportTab, 'text');
            app.OutputdofNumberEditField.Value = '1';
            % Show the figure after all components are created
            app.nm_mdof_forceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nm_mdof_arbitrary_single_force_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.nm_mdof_forceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.nm_mdof_forceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nm_mdof_forceUIFigure)
        end
    end
end