classdef oaspl_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIOASPLUIFigure               matlab.ui.Figure
        SelectInputArrayListBox       matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton              matlab.ui.control.Button
        text1                         matlab.ui.control.Label
        listbox_method                matlab.ui.control.ListBox
        ReturnButton                  matlab.ui.control.Button
        oaspl_aver13byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        OverallSPLTab                 matlab.ui.container.Tab
        TitleEditField                matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        OASPLEditField                matlab.ui.control.NumericEditField
        OASPLdBRef20microPaLabel      matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        GridListBox                   matlab.ui.control.ListBox
        GridListBoxLabel              matlab.ui.control.Label
        PlotTypeListBox               matlab.ui.control.ListBox
        PlotTypeListBoxLabel          matlab.ui.control.Label
        FunctionListBox               matlab.ui.control.ListBox
        FunctionListBoxLabel          matlab.ui.control.Label
        ConvertSPLtoPressurePSDTab    matlab.ui.container.Tab
        OutputPSDArrayNameoptionalEditField  matlab.ui.control.EditField
        OutputPSDArrayNameoptionalEditFieldLabel  matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        OutputPressurePSDUnitListBox  matlab.ui.control.ListBox
        OutputPressurePSDUnitListBoxLabel  matlab.ui.control.Label
        ApplyAweightsTab              matlab.ui.container.Tab
        OutputSPLArrayNameoptionalEditField  matlab.ui.control.EditField
        OutputSPLArrayNameoptionalLabel  matlab.ui.control.Label
        PlotTypeListBox_2             matlab.ui.control.ListBox
        PlotTypeListBox_2Label        matlab.ui.control.Label
        GridListBox_2                 matlab.ui.control.ListBox
        GridListBox_2Label            matlab.ui.control.Label
        ReferenceButton               matlab.ui.control.Button
        CalculateButton_3             matlab.ui.control.Button
        TitleEditField_3              matlab.ui.control.EditField
        TitleEditField_3Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        FS  %
    end
    
    methods (Access = private)
        
       function ClosePlots(app)          

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end      
       end     

       function update(app)
            ClosePlots(app);
            try
                cell_array=update_spl();
            catch
                msgbox('updata_spl found no files');
            end
            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
                app.listbox_method.Value=app.listbox_method.Items{1}; 
            catch
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OASPLEditField.Enable='off';
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    app.FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',app.FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end
            
            ttt=app.TitleEditField.Value;
            
            ng=app.GridListBox.Value;

            [~,n_type]=octave_test(app.THM);
            
            pt=app.PlotTypeListBox.Value;
            nf=app.FunctionListBox.Value;
  
            db=app.THM(:,2);
            [oadb]=oaspl_function(db);
            
     %       s1=sprintf('%8.4g',oadb);
           
            app.OASPLEditField.Enable='on';
            app.OASPLEditField.Value=oadb;
                      
            fprintf('\n  Overall Level = %8.4g dB \n',oadb);
            disp('  ');
            disp(' zero dB Reference = 20 micro Pascal ');
            disp('  ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(contains(nf,'SPL'))
                if(n_type==3)
                    t_string=sprintf(' One-Third Octave Sound Pressure Level, %s\n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                else
                    t_string=sprintf(' Full Octave Sound Pressure Level, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);    
                end    
            else
                if(n_type==3)
                    t_string=sprintf(' One-Third Octave FPL, %s \n OAFPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                else
                    t_string=sprintf(' Full Octave FPL, %s \n OAFPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);    
                end      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                        
            if(contains(pt,'Line'))
                
                figure(fig_num);
                
                plot(app.THM(:,1),app.THM(:,2));
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
                
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);     
                end
                
                dB=app.THM(:,2);
            
                [ymin,ymax]=dB_ylimits(dB);
                
                ylim([ymin,ymax]);    
                
                title(t_string)
                xlabel('Center Frequency (Hz)');
                if(contains(nf,'SPL'))
                    ylabel('SPL (dB)');
                else
                    ylabel('FPL (dB)');        
                end

                if(strcmp(ng,'Major On  Minor On'))
                    grid on;
                    set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','lin');
                end    
                if(strcmp(ng,'Major On  Minor Off'))
                    grid on;
                    set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','log','YScale','lin');
                end
                if(strcmp(ng,'Off'))
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','log','YScale','lin');
                end
            
            else
               
                data=app.THM;
                
                xlab='Center Frequency (Hz)';
                
                if(contains(nf,'SPL'))
                    ylab='SPL (dB)';
                else
                    ylab='FPL (dB)';        
                end    
                    
                if(n_type==3)
                    [fig_num]=plot_one_onethird_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);
                else
                    [fig_num]=plot_one_one_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);       
                end    
                
            
            end
                                  
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    app.FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',app.FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end

            [~,n_type]=octave_test(app.THM);

            ipress=app.OutputPressurePSDUnitListBox.Value;

            dB=app.THM(:,2);
            %
            [oadb]=oaspl_function(dB);
            %
            fprintf('\n  Overall Level = %8.4g dB  Ref: 20 micro Pascal\n\n',oadb);
            %
            figure(fig_num);
            fig_num=fig_num+1;
            %
            plot(app.THM(:,1),app.THM(:,2));

            f=app.THM(:,1);
            dB=app.THM(:,2);
            
            fmin=min(f);
            fmax=max(f);

            [ymin,ymax]=dB_ylimits(dB);

            if(fmin>20)
                fmin=20;
            end

%
            [xtt,xTT,iflag]=xtick_label(fmin,fmax);

            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
                fmin=min(xtt);
                fmax=max(xtt);
            end

            axis([fmin,fmax,ymin,ymax]);

            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log',...
                                 'YScale','log','XminorTick','off','YminorTick','off');
            %
            set(gca,'XGrid','on','GridLineStyle','-');
            set(gca,'YGrid','on','GridLineStyle','-');            

            out1=sprintf(' One-Third Octave Sound Pressure Level \n OASPL = %7.4g dB    Ref:  20 micro Pa',oadb);
            title(out1)
            xlabel(' Center Frequency (Hz) ');
            ylabel(' SPL (dB) ');
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(strcmp(ipress,'psi^2/Hz'))
                ref = 2.9e-09;
            end
            if(strcmp(ipress,'Pa^2/Hz'))
                ref = 20.e-06;
            end
            if(strcmp(ipress,'micro Pa^2/Hz'))
                ref = 20.;
            end
            %
            fc=app.THM(:,1);
            spl=app.THM(:,2);
            %
            [~,n_type]=octave_test(app.THM);
            [power_spectral_density,rms]=spl_to_psd_function_alt(fc,spl,ref,n_type);
            %
            
            if(strcmp(ipress,'psi^2/Hz'))
                disp(' Dimensions are: freq(Hz) & PSD(psi^2/Hz) ');
                ylab='Pressure (psi^2/Hz)';
                tstring=sprintf('Power Spectral Density  %8.3g psi rms overall',rms);
            end
            if(strcmp(ipress,'Pa^2/Hz'))
                disp(' Dimensions are: freq(Hz) & PSD(Pascal^2/Hz) ');
                ylab='Pressure (Pascal^2/Hz)'; 
                tstring=sprintf('Power Spectral Density  %8.3g Pa rms overall',rms);    
            end
            if(strcmp(ipress,'micro Pa^2/Hz'))
                disp(' Dimensions are: freq(Hz) & PSD(micro Pascal^2/Hz) ');
                ylab='Pressure (micro Pascal^2/Hz)';     
                tstring=sprintf('Power Spectral Density  %8.3g micro Pa rms overall',rms);    
            end
            disp(' ');
            disp(tstring);
            
            %
            %     
            figure(fig_num);
            ppp=power_spectral_density;
            x_label='Frequency (Hz)';
            y_label=ylab;
            t_string=tstring;
            fmin=fc(1);
            fmax=fc(end);
            
            md=6;
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            try
                name=app.OutputPSDArrayNameoptionalEditField.Value;
                if(~isempty(name))
                    assignin('base',name, ppp); 
                end
            catch
            end
           
        end

        % Button pushed function: ReferenceButton
        function ReferenceButtonPushed(app, event)
            web('https://en.wikipedia.org/wiki/A-weighting');
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               app.THM

               try
                    update(app);             
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    

        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
             ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
             
            if(n==1)
                try
                    app.FS=app.SelectInputArrayListBox.Value;
                    app.THM=evalin('base',app.FS);
                catch
                    warndlg('Input Array does not exist.  Try reading in external file.')
                    return;
                end
            end
            
            ttt=app.TitleEditField_3.Value;
            
            ng=app.GridListBox_2.Value;
            pt=app.PlotTypeListBox_2.Value;

            [~,n_type]=octave_test(app.THM);
         
            num=length(app.THM(:,1));

            for i=1:num
                [Aw]=A_weight_function(app.THM(i,1));
                app.THM(i,2)=app.THM(i,2)+Aw;
            end    

            db=app.THM(:,2);
            [oadb]=oaspl_function(db);
            
     %       s1=sprintf('%8.4g',oadb);
           
            app.OASPLEditField.Enable='on';
            app.OASPLEditField.Value=oadb;
                      
            fprintf('\n  Overall Level = %8.4g dBA \n',oadb);
            disp('  ');
            disp(' zero dB Reference = 20 micro Pascal ');
            disp('  ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                      
            if(n_type==3)
                t_string=sprintf(' One-Third Octave Sound Pressure Level, %s\n OASPL = %8.4g dBA  Ref: 20 micro Pa ',ttt,oadb);
            else
                t_string=sprintf(' Full Octave Sound Pressure Level, %s \n OASPL = %8.4g dBA  Ref: 20 micro Pa ',ttt,oadb);    
            end    

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                        
            if(contains(pt,'Line'))
                
                figure(fig_num);
                
                plot(app.THM(:,1),app.THM(:,2));
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
                
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);     
                end
                
                dB=app.THM(:,2);
            
                [ymin,ymax]=dB_ylimits(dB);
                
                ylim([ymin,ymax]);    
                
                title(t_string)
                xlabel('Center Frequency (Hz)');
                ylabel('SPL (dBA)');
                
                if(strcmp(ng,'Major On  Minor On'))
                    grid on;
                    set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                    set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','lin');
                end    
                if(strcmp(ng,'Major On  Minor Off'))
                    grid on;
                    set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','-','XScale','log','YScale','lin');
                end
                if(strcmp(ng,'Off'))
                    set(gca,'MinorGridLineStyle','none','GridLineStyle','none','XScale','log','YScale','lin');
                end
            
            else
               
                data=app.THM;
                
                xlab='Center Frequency (Hz)';
                ylab='SPL (dBA)';
                    
                if(n_type==3)
                    [fig_num]=plot_one_onethird_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);
                else
                    [fig_num]=plot_one_one_bar_ng_alt(fig_num,xlab,ylab,data,t_string,ng);       
                end    
                            
            end

            try
                name=app.OutputSPLArrayNameoptionalEditField.Value;
                if(~isempty(name))
                    assignin('base',name, app.THM); 
                end
            catch
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIOASPLUIFigure = uifigure('Visible', 'off');
            app.UIOASPLUIFigure.Name = 'UI OASPL';
            app.TabGroup = uitabgroup(app.UIOASPLUIFigure);
            app.OverallSPLTab = uitab(app.TabGroup);
            app.OverallSPLTab.Title = 'Overall SPL';
            app.FunctionListBoxLabel = uilabel(app.OverallSPLTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.OverallSPLTab);
            app.FunctionListBox.Items = {'SPL', 'FPL'};
            app.FunctionListBox.Value = 'SPL';
            app.PlotTypeListBoxLabel = uilabel(app.OverallSPLTab);
            app.PlotTypeListBoxLabel.Text = 'Plot Type';
            app.PlotTypeListBox = uilistbox(app.OverallSPLTab);
            app.PlotTypeListBox.Items = {'Line', 'Bar'};
            app.PlotTypeListBox.Value = 'Line';
            app.GridListBoxLabel = uilabel(app.OverallSPLTab);
            app.GridListBoxLabel.Text = 'Grid';
            app.GridListBox = uilistbox(app.OverallSPLTab);
            app.GridListBox.Items = {'Major On  Minor On', 'Major On  Minor Off', 'Off', ''};
            app.GridListBox.Value = 'Major On  Minor On';
            app.CalculateButton = uibutton(app.OverallSPLTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OASPLdBRef20microPaLabel = uilabel(app.OverallSPLTab);
            app.OASPLdBRef20microPaLabel.Text = {'OASPL (dB)   '; 'Ref:  20 micro Pa'};
            app.OASPLEditField = uieditfield(app.OverallSPLTab, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.OverallSPLTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.OverallSPLTab, 'text');
            app.ConvertSPLtoPressurePSDTab = uitab(app.TabGroup);
            app.ConvertSPLtoPressurePSDTab.Title = 'Convert SPL to Pressure PSD';
            app.OutputPressurePSDUnitListBoxLabel = uilabel(app.ConvertSPLtoPressurePSDTab);
            app.OutputPressurePSDUnitListBoxLabel.Text = 'Output Pressure PSD Unit';
            app.OutputPressurePSDUnitListBox = uilistbox(app.ConvertSPLtoPressurePSDTab);
            app.OutputPressurePSDUnitListBox.Items = {'psi^2/Hz', 'Pa^2/Hz', 'micro Pa^2/Hz'};
            app.OutputPressurePSDUnitListBox.Value = 'psi^2/Hz';
            app.CalculateButton_2 = uibutton(app.ConvertSPLtoPressurePSDTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.OutputPSDArrayNameoptionalEditFieldLabel = uilabel(app.ConvertSPLtoPressurePSDTab);
            app.OutputPSDArrayNameoptionalEditFieldLabel.Text = 'Output PSD Array Name (optional)';
            app.OutputPSDArrayNameoptionalEditField = uieditfield(app.ConvertSPLtoPressurePSDTab, 'text');
            app.ApplyAweightsTab = uitab(app.TabGroup);
            app.ApplyAweightsTab.Title = 'Apply A-weights';
            app.TitleEditField_3Label = uilabel(app.ApplyAweightsTab);
            app.TitleEditField_3Label.Text = 'Title';
            app.TitleEditField_3 = uieditfield(app.ApplyAweightsTab, 'text');
            app.CalculateButton_3 = uibutton(app.ApplyAweightsTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ReferenceButton = uibutton(app.ApplyAweightsTab, 'push');
            app.ReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceButtonPushed, true);
            app.ReferenceButton.Text = 'Reference';
            app.GridListBox_2Label = uilabel(app.ApplyAweightsTab);
            app.GridListBox_2Label.Text = 'Grid';
            app.GridListBox_2 = uilistbox(app.ApplyAweightsTab);
            app.GridListBox_2.Items = {'Major On  Minor On', 'Major On  Minor Off', 'Off', ''};
            app.GridListBox_2.Value = 'Major On  Minor On';
            app.PlotTypeListBox_2Label = uilabel(app.ApplyAweightsTab);
            app.PlotTypeListBox_2Label.Text = 'Plot Type';
            app.PlotTypeListBox_2 = uilistbox(app.ApplyAweightsTab);
            app.PlotTypeListBox_2.Items = {'Line', 'Bar'};
            app.PlotTypeListBox_2.Value = 'Line';
            app.OutputSPLArrayNameoptionalLabel = uilabel(app.ApplyAweightsTab);
            app.OutputSPLArrayNameoptionalLabel.Text = 'Output SPL Array Name (optional)';
            app.OutputSPLArrayNameoptionalEditField = uieditfield(app.ApplyAweightsTab, 'text');
            app.oaspl_aver13byTomIrvineLabel = uilabel(app.UIOASPLUIFigure);
            app.oaspl_aver13byTomIrvineLabel.Text = 'oaspl_a   ver 1.3   by Tom Irvine          The input array must be a full or one-third octave SPL with two columns:  freq (Hz) & SPL (dB)';
            app.ReturnButton = uibutton(app.UIOASPLUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.listbox_method = uilistbox(app.UIOASPLUIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIOASPLUIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIOASPLUIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIOASPLUIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIOASPLUIFigure);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            % Show the figure after all components are created
            app.UIOASPLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oaspl_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIOASPLUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIOASPLUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIOASPLUIFigure)
        end
    end
end