classdef panel_point_force_multi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel1              matlab.ui.container.Panel
        ForcePinkNoiseButton  matlab.ui.control.Button
        UniformLossFactor     matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        pushbutton_return     matlab.ui.control.Button
        pushbutton_calculate  matlab.ui.control.Button
        text23                matlab.ui.control.Label
        listbox_band          matlab.ui.control.ListBox
        text22                matlab.ui.control.Label
        text21                matlab.ui.control.Label
        edit_input_array      matlab.ui.control.EditField
        text17                matlab.ui.control.Label
        pushbutton4           matlab.ui.control.Button
        edit_area             matlab.ui.control.EditField
        text_area             matlab.ui.control.Label
        text_thick            matlab.ui.control.Label
        edit_thick            matlab.ui.control.EditField
        edit_mu               matlab.ui.control.EditField
        text_mu               matlab.ui.control.Label
        edit_md               matlab.ui.control.EditField
        text_md               matlab.ui.control.Label
        edit_em               matlab.ui.control.EditField
        text_em               matlab.ui.control.Label
        listbox_material      matlab.ui.control.ListBox
        text_material         matlab.ui.control.Label
        pushbutton_eq         matlab.ui.control.Button
        listbox_units         matlab.ui.control.ListBox
        text4                 matlab.ui.control.Label
        listbox_structure     matlab.ui.control.ListBox
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
    end
    
    methods (Access = private)
        function change(app, hObject, eventdata, handles)
            %
            iu=get(handles.listbox_units,'Value');
            ns=get(handles.listbox_structure,'Value');
            imat=get(handles.listbox_material,'Value');
            
            if(iu==1)  % English

                set(handles.text_thick,'String','Thickness (in)');
                set(handles.text_em,'String','Elastic Modulus (psi)');
                set(handles.text_md,'String','Mass Density (lbm/in^3)');
                set(handles.text_area,'String','Area (in^2)');
            else

                set(handles.text_thick,'String','Thickness (mm)');
                set(handles.text_em,'String','Elastic Modulus (GPa)');
                set(handles.text_md,'String','Mass Density (kg/m^3)');
                set(handles.text_area,'String','Area (m^2)');
            end
            
            [elastic_modulus,mass_density,poisson]=six_materials(iu,imat);
            
            %%%%
            
            if(imat<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
                    ss3=sprintf('%8.4g',poisson);
            else
                    ss1=' ';
                    ss2=' ';
                    ss3=' ';
            end
            
            set(handles.edit_em,'String',ss1);
            set(handles.edit_md,'String',ss2);
            set(handles.edit_mu,'String',ss3);
        end
        
        function edit_force_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_force (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_freq_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_freq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_lf_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_lf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_mass_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_mass (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_mdens_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_mdens (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function panel_point_force_multi_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to panel_point_force_multi (see VARARGIN)
            
            % Choose default command line output for panel_point_force_multi
            handles.output = hObject;
            
            change(app, hObject, eventdata, handles);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_material
        function listbox_material_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_material (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_material contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_material
            change(app, hObject, eventdata, handles);
        end

        % Value changed function: listbox_structure
        function listbox_structure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_structure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_structure contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_structure
            change(app, hObject, eventdata, handles);
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_units contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_units
            change(app, hObject, eventdata, handles);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            A = imread('velox_point_force.jpg');
            figure(999)
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

             try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end           


            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            tpi=2*pi;
            
            fig_num=1;
            
            x_label='Frequency (Hz)';
            md=4;
            
            iu=get(handles.listbox_units,'Value');
            ns=get(handles.listbox_structure,'Value');
            
            %%%%%%
            
            try
                FS=get(handles.edit_input_array,'String');
                app.THM=evalin('base',FS);
            
            catch
                warndlg('Input Array does not exist.  Try again.')
                return;
            end
            
            sz=size(app.THM);
            
            if(sz(2)==2)
                ulf=app.UniformLossFactor.Value;
                app.THM(:,3)=ulf;
            end
            
            %%%%%%
            
            fc=app.THM(:,1);
            NL=length(fc);
            fmin=min(fc);
            fmax=max(fc);
            
            
            F=app.THM(:,2);
            lf=app.THM(:,3);
            
            if(ns==1 || ns==2)  % Thin Plate
            
                area=str2num(get(handles.edit_area,'String'));
            
                E=str2num(get(handles.edit_em,'String'));
                rho=str2num(get(handles.edit_md,'String'));
                mu=str2num(get(handles.edit_mu,'String'));
                h=str2num(get(handles.edit_thick,'String'));
            
                if(iu==1)
                   rho=rho/386;
                else
                   h=h/1000;
                   [E]=GPa_to_Pa(E);
                end
            
                B=E*h^3/(12*(1-mu^2));
            
                Z=sqrt(B*rho*h);
            
                if(ns==1) % Middle
                    Z=Z*8;
                else      % Edge
                    Z=Z*3.5;
                end
                Y=1/Z;
            end
            
            mass=rho*area*h;
            
            [modal_density_Hz,~,~,~]=...
                                     panel_generic_modal_density(E,mu,rho,h,area);
            
            
            vel=zeros(NL,1);
            accel=zeros(NL,1);
            Moverlap=zeros(NL,1);
            
            for i=1:NL
            
                omega=tpi*fc(i);
            
                vel2 = F(i)^2*real(Y)/(mass*lf(i)*omega);
            
                vel(i) = sqrt(vel2);
            
                accel(i)=vel(i)*omega;
            
                Moverlap(i)=modal_density_Hz*lf(i)*fc(i);
            
            end
            
            nb=get(handles.listbox_band,'Value');
            
            if(iu==1)
               VU='in/sec';
               YU='(in/sec)/lbf';
               ZU='(lbf-sec)/in';
               FU='lbf';
            else
               VU='mm/sec';
               YU='(m/sec)/N';
               ZU='(N-sec)/m';
               FU='N';
            end
            
            disp(' ');
            disp(' * * * ');
            disp(' ');

            if(iu==1)
               accel=accel/386;
            else
               accel=accel/9.81;
               vel=vel*1000;
            end
            
            disp('Spatial Averages, Reverberant Field');
            disp(' ');
            disp('  Velocity & Accel are RMS ');
            disp(' ');
                disp('  Freq   Force   Loss     Velocity     Accel    Modal');
            
            if(iu==1)
                disp('  (Hz)   (lbf)  Factor     (in/sec)     (G)    Overlap');
            else
                disp('  (Hz)   (N)    Factor     (mm/sec)     (G)    Overlap');
            end
            
            for i=1:NL
                fprintf('  %g %7.4g  %8.4g   %8.4g  %8.4g  %7.3g \n',...
                                         fc(i),F(i),lf(i),vel(i),accel(i),Moverlap(i));
            end
            
            %%
            
            frms=sqrt(sum(F.^2));
            vrms=sqrt(sum(vel.^2));
            arms=sqrt(sum(accel.^2));
            
            %%%%%%%%%%%%%
            
            fc=fix_size(fc);
            vel=fix_size(vel);
            accel=fix_size(accel);
            
              vel_ps=[fc vel];
            accel_ps=[fc accel];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp1=vel_ps;

            if(iu==1)
                t_string=sprintf('Velocity Spectrum   %7.3g in/sec rms',vrms);
            else
                t_string=sprintf('Velocity Spectrum   %7.3g mm/sec rms',vrms);
            end
            
            if(iu==1)
                y_label='Vel (in/sec rms)';
            else
                y_label='Vel (mm/sec rms)';
            end
       
            ng=2;

            if(nb~=1)

               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,fmin,fmax,md);

            else   
       
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            aaa1=accel_ps;
            
            y_label='Accel (G rms)';
            
            t_string=sprintf('Acceleration Spectrum   %7.3g G rms',arms);

            if(nb~=1)
               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,aaa1,fmin,fmax,md);
            else   
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,aaa1,t_string,ng);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            qqq1=[fc F];
            
            if(iu==1)
               y_label='Force (lbf rms)';
               t_string=sprintf('Input Force Spectrum   %7.3g lbf rms',frms);
            else
               y_label='Force (N rms)';
               t_string=sprintf('Input Force Spectrum   %7.3g N rms',frms);       
            end

            if(nb~=1)
            
               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,qqq1,fmin,fmax,md);     
            else 
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,qqq1,t_string,ng);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            rrr1=[fc lf];
            
            t_string='Total Loss Factor';
            
            y_label='Loss Factor';

            if(nb~=1)

                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);
            else

                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,rrr1,t_string,ng);

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(nb<=2)
            
                [psd,~]=psd_from_spectrum(nb,fc,accel);
            
                disp(' ');
                disp(' Power Spectral Density ');
                disp(' ');
                disp('   Freq    Accel');
                disp('   (Hz)   (G^2/Hz)');
            
                for i=1:NL
                    fprintf('   %g   %8.4g \n',fc(i),psd(i));
                end
            
                accel_psd=[fc psd];
            
                rrr1=accel_psd;
            
                y_label='Accel (G^2/Hz)';
            
                t_string=sprintf('Power Spectral Density  %7.3g GRMS Overall',arms);
            
                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);
            
            end
            %%%%%%%%%%%%%%%%%
            
            disp(' ');
            if(iu==1)
                fprintf(' Overall Velocity     = %7.3g in/sec rms \n',vrms);
            else
                fprintf(' Overall Velocity     = %7.3g mm/sec rms \n',vrms);
            end
            
            fprintf(' Overall Acceleration = %7.3g G rms \n',arms);
            
            %%

            disp(' ');
            disp(' Driving Point Real Values ');
            fprintf('\n  Mobility = %8.4g %s \n',Y,YU);
            fprintf(' Impedance = %8.4g %s \n\n',Z,ZU);

            fprintf('\n Modal density = %7.3g modes/Hz \n\n',modal_density_Hz);

            if(iu==1)
                fprintf(' Total mass = %7.3g lbm \n\n',mass*386);
            else
                fprintf(' Total mass = %7.3g kg \n\n',mass);           
            end
            
            %%%%%%%%%%%%%%%%%
            
            assignin('base', 'vel_ps',vel_ps);
            assignin('base', 'accel_ps',accel_ps);

            disp('Output arrays: ');
            disp('  Power Spectra:          vel_ps & accel_ps ');

            
            if(nb<=2)
                disp('  Power Spectral Density: accel_psd ');              
                assignin('base', 'accel_psd',accel_psd);                 
            end
            
            disp(' ');
            
            msgbox('Results written to command window');
        end

        % Button pushed function: pushbutton_eq
        function pushbutton_eq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_eq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            A = imread('mi_thin_plate.jpg');
            figure(998)
            imshow(A,'border','tight','InitialMagnification',100)
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: ForcePinkNoiseButton
        function ForcePinkNoiseButtonPushed(app, event)
             app.edit_input_array.Value='force_pink_noise_rms';    

                  app.THM=[  10 	         2 
                  12.5 	         2 
                    16 	         2 
                    20 	         2 
                    25 	         2 
                  31.5 	         2 
                    40 	         2 
                    50 	         2 
                    63 	         2 
                    80 	         2 
                   100 	         2 
                   125 	         2 
                   160 	         2 
                   200 	         2 
                   250 	         2 
                   315 	         2 
                   400 	         2 
                   500 	         2 
                   630 	         2 
                   800 	         2 
                  1000 	         2 
                  1250 	         2 
                  1600 	         2 
                  2000 	         2 
                  2500 	         2 
                  3150 	         2 
                  4000 	         2 
                  5000 	         2 ];

                  assignin('base','force_pink_noise_rms',app.THM)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'panel_point_force_multi';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'panel_point_force_multi.m  ver 1.2  by Tom Irvine           Panel excited by point force.';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Enter Data';
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Structure';
            app.listbox_structure = uilistbox(app.uipanel1);
            app.listbox_structure.Items = {'Thin Plate, Force at Middle Point', 'Thin Plate, Force at Edge Point  '};
            app.listbox_structure.ValueChangedFcn = createCallbackFcn(app, @listbox_structure_Callback, true);
            app.listbox_structure.Tag = 'listbox_structure';
            app.listbox_structure.Value = 'Thin Plate, Force at Middle Point';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Select Units';
            app.listbox_units = uilistbox(app.uipanel1);
            app.listbox_units.Items = {'English', 'Metric'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'English';
            app.pushbutton_eq = uibutton(app.uipanel1, 'push');
            app.pushbutton_eq.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_eq_Callback, true);
            app.pushbutton_eq.Tag = 'pushbutton_eq';
            app.pushbutton_eq.Text = 'Mobility';
            app.text_material = uilabel(app.uipanel1);
            app.text_material.Tag = 'text_material';
            app.text_material.Text = 'Select Material';
            app.listbox_material = uilistbox(app.uipanel1);
            app.listbox_material.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.listbox_material.ValueChangedFcn = createCallbackFcn(app, @listbox_material_Callback, true);
            app.listbox_material.Tag = 'listbox_material';
            app.listbox_material.Value = 'Aluminum';
            app.text_em = uilabel(app.uipanel1);
            app.text_em.Tag = 'text_em';
            app.text_em.Text = 'Elastic Modulus (psi)';
            app.edit_em = uieditfield(app.uipanel1, 'text');
            app.edit_em.Tag = 'edit_em';
            app.text_md = uilabel(app.uipanel1);
            app.text_md.Tag = 'text_md';
            app.text_md.Text = 'Mass Density (lbm/in^3)';
            app.edit_md = uieditfield(app.uipanel1, 'text');
            app.edit_md.Tag = 'edit_md';
            app.text_mu = uilabel(app.uipanel1);
            app.text_mu.Tag = 'text_mu';
            app.text_mu.Text = 'Poisson ratio';
            app.edit_mu = uieditfield(app.uipanel1, 'text');
            app.edit_mu.Tag = 'edit_mu';
            app.edit_thick = uieditfield(app.uipanel1, 'text');
            app.edit_thick.Tag = 'edit_thick';
            app.text_thick = uilabel(app.uipanel1);
            app.text_thick.Tag = 'text_thick';
            app.text_thick.Text = 'Thickness (in)';
            app.text_area = uilabel(app.uipanel1);
            app.text_area.Tag = 'text_area';
            app.text_area.Text = 'Surface Area (in^2)';
            app.edit_area = uieditfield(app.uipanel1, 'text');
            app.edit_area.Tag = 'edit_area';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Velocity';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = {'The input array must have at least two columns:'; '   '; '  1.  Center Frequency (Hz)     '; '  2.  Point Force RMS   '; '  3.  Total Loss Factor (optional)'};
            app.edit_input_array = uieditfield(app.uipanel1, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Enter Input Array Name';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Select Frequency Spacing';
            app.listbox_band = uilistbox(app.uipanel1);
            app.listbox_band.Items = {'One-third Octave', 'Octave', 'Other'};
            app.listbox_band.Tag = 'listbox_band';
            app.listbox_band.Value = 'One-third Octave';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'View Equations';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.EditFieldLabel = uilabel(app.uipanel1);
            app.EditFieldLabel.Text = {'Uniform Loss Factor if the Input '; 'Array only has Two Columns'};
            app.UniformLossFactor = uieditfield(app.uipanel1, 'numeric');
            app.UniformLossFactor.Value = 0.01;
            app.ForcePinkNoiseButton = uibutton(app.uipanel1, 'push');
            app.ForcePinkNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ForcePinkNoiseButtonPushed, true);
            app.ForcePinkNoiseButton.Text = 'Force Pink Noise';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panel_point_force_multi_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)panel_point_force_multi_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end