classdef panel_point_force_multi_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeometryMaterialTab             matlab.ui.container.Tab
        UniformLossFactorEditField      matlab.ui.control.EditField
        UniformLossFactoriftheInputArrayonlyhasTwoColumnsEditFieldLabel  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        StructureListBox                matlab.ui.control.ListBox
        StructureListBoxLabel           matlab.ui.control.Label
        Areain2EditField_2              matlab.ui.control.EditField
        Areain2EditField_2Label         matlab.ui.control.Label
        ThicknessinEditField            matlab.ui.control.EditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        ForceSpectrumInputTab           matlab.ui.container.Tab
        OutputArrayNamesLabel           matlab.ui.control.Label
        AccelerationPSDEditField        matlab.ui.control.EditField
        AccelerationPSDEditFieldLabel   matlab.ui.control.Label
        AccelerationSpectrumEditField   matlab.ui.control.EditField
        AccelerationSpectrumEditFieldLabel  matlab.ui.control.Label
        VelocitySpectrumEditField       matlab.ui.control.EditField
        VelocitySpectrumEditFieldLabel  matlab.ui.control.Label
        SelectFrequencySpacingListBox   matlab.ui.control.ListBox
        SelectFrequencySpacingListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        text17                          matlab.ui.control.Label
        CalculateButton_spectrum        matlab.ui.control.Button
        ForcePSDInputTab                matlab.ui.container.Tab
        OutputAccelerationPSDEditField  matlab.ui.control.EditField
        OutputAccelerationPSDEditFieldLabel  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        listbox_method_psd              matlab.ui.control.ListBox
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        text_psd                        matlab.ui.control.Label
        CalculateButton_psd             matlab.ui.control.Button
        ViewEquationsResultsTab         matlab.ui.container.Tab
        WriteDisplayResultsTableButton  matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        pushbutton4                     matlab.ui.control.Button
        pushbutton_eq                   matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM   % Description
        area  %
        thick %
        rho   %
        mu    %
        E     %
        iu    %
        mat   %
        loc   %
        Y     %
        Z     %
        mass  %
        modal_density_Hz %
        nb    %
        VU    %
        YU    %
        ZU    %
        FU    %
        av    %
    end
    
    methods (Access = private)

        function clear(app)
            ClosePlots(app); 

            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end    

        function nbands(app)
            Items = char(app.SelectFrequencySpacingListBox.Items);
            im=app.SelectFrequencySpacingListBox.Value;
            [app.nb]=find_index(Items,im);     

            if(app.nb<=2)
                app.AccelerationPSDEditField.Visible='on';
                app.AccelerationPSDEditFieldLabel.Visible='on';
            else
                app.AccelerationPSDEditField.Visible='off';
                app.AccelerationPSDEditFieldLabel.Visible='off';            
            end    
        end    

        function update_psd(app)
            cell_array=update_power_spectral_density();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));  
            app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
        end    
        function update_spectrum(app)
            cell_array=update_psd_srs_spl_fds_23();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method.Value=app.listbox_method.Items{1};
        end

        function change(app)

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.mat]=find_index(Items,im);   

            if(app.iu==1)
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (lbf/in^2)';
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
            else    
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)';
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
            end    

            [app.E,app.rho,app.mu]=six_materials(app.iu,app.mat);

            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);            
            app.PoissonRatioEditField.Value=sprintf('%g',app.mu); 

        end    

        function Pink(app)
            app.THM=[  10 	         2 
                  12.5 	         2 
                    16 	         2 
                    20 	         2 
                    25 	         2 
                  31.5 	         2 
                    40 	         2 
                    50 	         2 
                    63 	         2 
                    80 	         2 
                   100 	         2 
                   125 	         2 
                   160 	         2 
                   200 	         2 
                   250 	         2 
                   315 	         2 
                   400 	         2 
                   500 	         2 
                   630 	         2 
                   800 	         2 
                  1000 	         2 
                  1250 	         2 
                  1600 	         2 
                  2000 	         2 
                  2500 	         2 
                  3150 	         2 
                  4000 	         2 
                  5000 	         2 ];

                  assignin('base','force_pink_noise_rms',app.THM);            
        end    
        
        function Preprocess(app)
            ClosePlots(app);

            Items = char(app.StructureListBox.Items);
            im=app.StructureListBox.Value;
            [app.loc]=find_index(Items,im);

            app.area=str2num(app.Areain2EditField_2.Value); 
            app.thick=str2num(app.ThicknessinEditField.Value);  
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);    
            app.mu=str2num(app.PoissonRatioEditField.Value);     
            app.E=str2num(app.ElasticModuluspsiEditField.Value);      
            
            if(app.iu==1)
                app.rho=app.rho/386;
            else
                app.thick=app.thick/1000;
                [app.E]=GPa_to_Pa(app.E);
            end
            
            B=app.E*app.thick^3/(12*(1-app.mu^2));
            
            app.Z=sqrt(B*app.rho*app.thick);
            
            if(app.loc==1) % Middle
                app.Z=app.Z*8;
            else      % Edge
                app.Z=app.Z*3.5;
            end
            
            app.Y=1/app.Z;
          
            app.mass=app.rho*app.area*app.thick;
            
            [app.modal_density_Hz,~,~,~]=...
                         panel_generic_modal_density(app.E,app.mu,app.rho,app.thick,app.area);

            if(app.iu==1)
               app.VU='in/sec';
               app.YU='(in/sec)/lbf';
               app.ZU='(lbf-sec)/in';
               app.FU='lbf';
            else
               app.VU='mm/sec';
               app.YU='(m/sec)/N';
               app.ZU='(N-sec)/m';
               app.FU='N';
            end

            s1=sprintf('Driving Point Real Values ');
            s2=sprintf('Mobility = %8.4g %s',app.Y,app.YU);
            s3=sprintf('Impedance = %8.4g %s',app.Z,app.ZU);

            s4=sprintf('Modal density = %7.3g modes/Hz',app.modal_density_Hz);

            if(app.iu==1)
                s5=sprintf('Total mass = %7.3g lbm',app.mass*386);
            else
                s5=sprintf('Total mass = %7.3g kg',app.mass);           
            end

            ss=sprintf('%s\n\n%s\n%s\n\n%s\n\n%s',s1,s2,s3,s4,s5);
            app.ResultsTextArea.Value=ss;

        end

        function postprocess(app)
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
        end
    
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Pink(app);
            change(app);
            update_spectrum(app);
            update_psd(app);
            nbands(app);
            clear(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_spectrum
        function CalculateButton_spectrumPushed(app, event)
            Preprocess(app);

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            sz=size(app.THM);        
            if(sz(2)==2)
                ulf=str2num(app.UniformLossFactorEditField.Value);
                app.THM(:,3)=ulf;
            end            

            tpi=2*pi;
            
            fig_num=1;
            
            x_label='Frequency (Hz)';
            md=4;

            %%%%%%
            
            fc=app.THM(:,1);
            NL=length(fc);
            fmin=min(fc);
            fmax=max(fc);
            
            F=app.THM(:,2);
            lf=app.THM(:,3);
            
            vel=zeros(NL,1);
            accel=zeros(NL,1);
            Moverlap=zeros(NL,1);
            
            for i=1:NL
            
                omega=tpi*fc(i);
           
                vel2 = F(i)^2*real(app.Y)/(app.mass*lf(i)*omega); 
                vel(i) = sqrt(vel2);
                accel(i)=vel(i)*omega;
            
                Moverlap(i)=app.modal_density_Hz*lf(i)*fc(i);
            
            end
            
            disp(' ');
            disp(' * * * ');
            disp(' ');

            if(app.iu==1)
               accel=accel/386;
            else
               accel=accel/9.81;
               vel=vel*1000;
            end
            
            s2=sprintf('Velocity & Accel are RMS');
            s3=sprintf('  Freq \t Force \t Loss \t Velocity \t Accel  \t Modal');
            
            if(app.iu==1)
                s4=sprintf('  (Hz) \t (lbf) \t Factor  \t (in/sec) \t   (G)\t Overlap');
            else
                s4=sprintf('  (Hz) \t  (N)  \t  Factor \t (mm/sec) \t   (G)\t Overlap');
            end

            ss=sprintf(' Spatial Averages, Reverberant Field \n\n %s\n\n%s\n%s\n %s\n',s2,s3,s4);
            
            for i=1:NL
                sq=sprintf(' %6.1f \t %7.4g \t %8.4g \t %8.4g \t %8.4g \t %7.3g \n',...
                                         fc(i),F(i),lf(i),vel(i),accel(i),Moverlap(i));
                ss=sprintf('%s %s',ss,sq);
            end
            
            %%
            
            frms=sqrt(sum(F.^2));
            vrms=sqrt(sum(vel.^2));
            arms=sqrt(sum(accel.^2));
            
            %%%%%%%%%%%%%
            
            fc=fix_size(fc);
            vel=fix_size(vel);
            accel=fix_size(accel);
            
              vel_ps=[fc vel];
            accel_ps=[fc accel];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp1=vel_ps;

            if(app.iu==1)
                t_string=sprintf('Velocity Spectrum   %7.3g in/sec rms',vrms);
            else
                t_string=sprintf('Velocity Spectrum   %7.3g mm/sec rms',vrms);
            end
            
            if(app.iu==1)
                y_label='Vel (in/sec rms)';
            else
                y_label='Vel (mm/sec rms)';
            end
       
            ng=2;

            if(app.nb~=1)

               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,fmin,fmax,md);

            else   
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,ppp1,t_string,ng);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            aaa1=accel_ps;
            
            y_label='Accel (G rms)';
            
            t_string=sprintf('Acceleration Spectrum   %7.3g G rms',arms);

            if(app.nb~=1)
               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,aaa1,fmin,fmax,md);
            else   
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,aaa1,t_string,ng);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            qqq1=[fc F];
            
            if(app.iu==1)
               y_label='Force (lbf rms)';
               t_string=sprintf('Input Force Spectrum   %7.3g lbf rms',frms);
            else
               y_label='Force (N rms)';
               t_string=sprintf('Input Force Spectrum   %7.3g N rms',frms);       
            end

            if(app.nb~=1)
            
               [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,qqq1,fmin,fmax,md);     
            else 
                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,qqq1,t_string,ng);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            rrr1=[fc lf];
            
            t_string='Total Loss Factor';
            y_label='Loss Factor';

            if(app.nb~=1)

                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);
            else

                [fig_num]=plot_one_onethird_bar_ng2(fig_num,x_label,y_label,rrr1,t_string,ng);

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.nb<=2)
            
                [psd,~]=psd_from_spectrum(app.nb,fc,accel);
            
                disp(' ');
                disp(' Power Spectral Density ');
                disp(' ');
                disp('   Freq    Accel');
                disp('   (Hz)   (G^2/Hz)');
            
                for i=1:NL
                    fprintf('   %g   %8.4g \n',fc(i),psd(i));
                end
            
                accel_psd=[fc psd];
            
                rrr1=accel_psd;
            
                y_label='Accel (G^2/Hz)';
            
                t_string=sprintf('Power Spectral Density  %7.3g GRMS Overall',arms);
            
                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);
            
            end
            %%%%%%%%%%%%%%%%%
            
           
            if(app.iu==1)
                s1=sprintf(' Overall Velocity     = %7.3g in/sec rms \n',vrms);
            else
                s1=sprintf(' Overall Velocity     = %7.3g mm/sec rms \n',vrms);
            end
            
            s2=sprintf(' Overall Acceleration = %7.3g G rms \n',arms);
            
            app.av=sprintf('%s\n %s %s',ss,s1,s2);

            %%
            
            %%%%%%%%%%%%%%%%%

            vn1=app.VelocitySpectrumEditField.Value;
            an1=app.AccelerationSpectrumEditField.Value;
            
            assignin('base', vn1,vel_ps);
            assignin('base', an1,accel_ps);

            if(app.nb<=2)            
                an2=app.AccelerationPSDEditField.Value;
                assignin('base', an2,accel_psd);                 
            end

            postprocess(app);

        end

        % Button pushed function: CalculateButton_psd
        function CalculateButton_psdPushed(app, event)
            Preprocess(app);
            tpi=2*pi;

            fig_num=1;

            try
                FS=app.SelectInputArrayListBox_psd.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end            
            
            sz=size(app.THM);        
            if(sz(2)==2)
                ulf=str2num(app.UniformLossFactorEditField.Value);
                app.THM(:,3)=ulf;
            end

            apsd=zeros(sz(1),2);

            apsd(:,1)=app.THM(:,1);

            F=app.THM(:,2);    
            lf=app.THM(:,3);

            for i=1:sz(1)
                omega=tpi*app.THM(i,1);
                vpsd = F(i)*real(app.Y)/(app.mass*lf(i)*omega); 
                apsd(i,2)=vpsd*omega^2;  
            end    

            if(app.iu==1)
               apsd(:,2)=apsd(:,2)/386^2;
            else
               apsd(:,2)=apsd(:,2)/9.81;
            end

            md=5;
            x_label='Frequency (Hz)';
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
            
            rrr1=app.THM;
            [~,frms] = calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2)); 

            if(app.iu==1)
                y_label='Force (lbf^2/Hz)';
                t_string=sprintf('Force Power Spectral Density  %7.3g lbf RMS Overall',frms);
            else
                y_label='Force (N^2/Hz)';
                t_string=sprintf('Force Power Spectral Density  %7.3g N RMS Overall',frms);               
            end    

            [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);

            rrr1=apsd;
            y_label='Accel (G^2/Hz)';
            [~,arms] = calculate_PSD_slopes_no(apsd(:,1),apsd(:,2)); 
            t_string=sprintf('Acceleration Power Spectral Density  %7.3g GRMS Overall',arms);
            [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);

            ss=sprintf(' Acceleration PSD \n\n freq \t  accel \n  (Hz) \t  (G^2/Hz) \n');

            for i=1:sz(1)
                sq=sprintf('%g \t %7.3g\n',apsd(i,1),apsd(i,2));
                ss=sprintf('%s %s',ss,sq);
            end    
            
            app.av=sprintf('%s \n\n Overall Level = %8.4g GRMS',ss,arms);

            try
                name=app.OutputAccelerationPSDEditField.Value;
                assignin('base',name,apsd);
            catch
            end

            postprocess(app);

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            clear(app);
            change(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            clear(app);
            change(app);
        end

        % Callback function
        function pushbutton_eqButtonPushed(app, event)
            
            A = imread('mi_thin_plate.jpg');
            figure(99)
            imshow(A,'border','tight','InitialMagnification',100);            
        end

        % Callback function
        function pushbutton4ButtonPushed(app, event)
            
            A = imread('velox_point_force.jpg');
            figure(100)
            imshow(A,'border','tight','InitialMagnification',100);         
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_spectrum(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            clear(app);
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
    
                   app.THM = readmatrix(filename);  
                   app.THM=fix_size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd_srs_spl_fds_23(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Value changed function: SelectFrequencySpacingListBox
        function SelectFrequencySpacingListBoxValueChanged(app, event)
            nbands(app);
            clear(app);
        end

        % Value changed function: StructureListBox
        function StructureListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: Areain2EditField_2
        function Areain2EditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: ThicknessinEditField
        function ThicknessinEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: UniformLossFactorEditField
        function UniformLossFactorEditFieldValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: WriteDisplayResultsTableButton
        function WriteDisplayResultsTableButtonPushed(app, event)
           
            [writefname, writepname] = uiputfile('*.txt','Save data as');
            writepfname = fullfile(writepname, writefname);
   
            writematrix(app.av,writepfname);

            winopen(writepfname);
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
            clear(app);
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
    
                   app.THM = readmatrix(filename);  
                   app.THM=fix_size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'panel_point_force_multi.m  ver 1.2  by Tom Irvine           Panel excited by point force.';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometryMaterialTab = uitab(app.TabGroup);
            app.GeometryMaterialTab.Title = 'Geometry & Material';
            app.MaterialListBoxLabel = uilabel(app.GeometryMaterialTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.GeometryMaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.ThicknessinEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.ThicknessinEditField.ValueChangingFcn = createCallbackFcn(app, @ThicknessinEditFieldValueChanging, true);
            app.Areain2EditField_2Label = uilabel(app.GeometryMaterialTab);
            app.Areain2EditField_2Label.Text = 'Area (in^2)';
            app.Areain2EditField_2 = uieditfield(app.GeometryMaterialTab, 'text');
            app.Areain2EditField_2.ValueChangingFcn = createCallbackFcn(app, @Areain2EditField_2ValueChanging, true);
            app.StructureListBoxLabel = uilabel(app.GeometryMaterialTab);
            app.StructureListBoxLabel.Text = 'Structure';
            app.StructureListBox = uilistbox(app.GeometryMaterialTab);
            app.StructureListBox.Items = {'Thin Plate, Force at Middle Point', 'Thin Plate, Force at Edge Point'};
            app.StructureListBox.ValueChangedFcn = createCallbackFcn(app, @StructureListBoxValueChanged, true);
            app.StructureListBox.Value = 'Thin Plate, Force at Middle Point';
            app.UnitsListBoxLabel = uilabel(app.GeometryMaterialTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryMaterialTab);
            app.UnitsListBox.Items = {'English', 'Metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.UniformLossFactoriftheInputArrayonlyhasTwoColumnsEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.UniformLossFactoriftheInputArrayonlyhasTwoColumnsEditFieldLabel.Text = {'Uniform Loss Factor if the Input '; 'Array only has Two Columns'};
            app.UniformLossFactorEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.UniformLossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @UniformLossFactorEditFieldValueChanged, true);
            app.UniformLossFactorEditField.Value = '0.01';
            app.ForceSpectrumInputTab = uitab(app.TabGroup);
            app.ForceSpectrumInputTab.Title = 'Force Spectrum Input';
            app.CalculateButton_spectrum = uibutton(app.ForceSpectrumInputTab, 'push');
            app.CalculateButton_spectrum.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_spectrumPushed, true);
            app.CalculateButton_spectrum.Text = 'Calculate';
            app.text17 = uilabel(app.ForceSpectrumInputTab);
            app.text17.Tag = 'text17';
            app.text17.Text = {'The input array must have at '; 'least two columns:'; '   '; '  1.  Center Frequency (Hz)     '; '  2.  Point Force RMS   '; '  3.  Total Loss Factor (optional)'};
            app.listbox_method = uilistbox(app.ForceSpectrumInputTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ForceSpectrumInputTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ForceSpectrumInputTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ForceSpectrumInputTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ForceSpectrumInputTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SelectFrequencySpacingListBoxLabel = uilabel(app.ForceSpectrumInputTab);
            app.SelectFrequencySpacingListBoxLabel.Text = 'Select Frequency Spacing';
            app.SelectFrequencySpacingListBox = uilistbox(app.ForceSpectrumInputTab);
            app.SelectFrequencySpacingListBox.Items = {'One-third Octave', 'Octave', 'Other'};
            app.SelectFrequencySpacingListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFrequencySpacingListBoxValueChanged, true);
            app.SelectFrequencySpacingListBox.Value = 'One-third Octave';
            app.VelocitySpectrumEditFieldLabel = uilabel(app.ForceSpectrumInputTab);
            app.VelocitySpectrumEditFieldLabel.Text = 'Velocity Spectrum';
            app.VelocitySpectrumEditField = uieditfield(app.ForceSpectrumInputTab, 'text');
            app.VelocitySpectrumEditField.Value = 'velocity_spectrum';
            app.AccelerationSpectrumEditFieldLabel = uilabel(app.ForceSpectrumInputTab);
            app.AccelerationSpectrumEditFieldLabel.Text = 'Acceleration Spectrum';
            app.AccelerationSpectrumEditField = uieditfield(app.ForceSpectrumInputTab, 'text');
            app.AccelerationSpectrumEditField.Value = 'accel_spectrum';
            app.AccelerationPSDEditFieldLabel = uilabel(app.ForceSpectrumInputTab);
            app.AccelerationPSDEditFieldLabel.Text = 'Acceleration PSD';
            app.AccelerationPSDEditField = uieditfield(app.ForceSpectrumInputTab, 'text');
            app.AccelerationPSDEditField.Value = 'accel_psd';
            app.OutputArrayNamesLabel = uilabel(app.ForceSpectrumInputTab);
            app.OutputArrayNamesLabel.Text = 'Output Array Names';
            app.ForcePSDInputTab = uitab(app.TabGroup);
            app.ForcePSDInputTab.Title = 'Force PSD Input';
            app.CalculateButton_psd = uibutton(app.ForcePSDInputTab, 'push');
            app.CalculateButton_psd.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_psdPushed, true);
            app.CalculateButton_psd.Text = 'Calculate';
            app.text_psd = uilabel(app.ForcePSDInputTab);
            app.text_psd.Tag = 'text17';
            app.text_psd.Text = {'The input PSD array must have two columns:  '; '    freq (Hz)  & force (unit^2/Hz)'; ''};
            app.text1_psd = uilabel(app.ForcePSDInputTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.SelectInputArrayListBox_2Label = uilabel(app.ForcePSDInputTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.ForcePSDInputTab);
            app.SelectInputArrayListBox_psd.Items = {};
            app.SelectInputArrayListBox_psd.Value = {};
            app.listbox_method_psd = uilistbox(app.ForcePSDInputTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.UpdateListButton_psd = uibutton(app.ForcePSDInputTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.OutputAccelerationPSDEditFieldLabel = uilabel(app.ForcePSDInputTab);
            app.OutputAccelerationPSDEditFieldLabel.Text = 'Output Acceleration PSD';
            app.OutputAccelerationPSDEditField = uieditfield(app.ForcePSDInputTab, 'text');
            app.OutputAccelerationPSDEditField.Value = 'accel_psd';
            app.ViewEquationsResultsTab = uitab(app.TabGroup);
            app.ViewEquationsResultsTab.Title = 'View Equations & Results';
            app.pushbutton_eq = uibutton(app.ViewEquationsResultsTab, 'push');
            app.pushbutton_eq.Tag = 'pushbutton_eq';
            app.pushbutton_eq.Text = 'Mobility';
            app.pushbutton4 = uibutton(app.ViewEquationsResultsTab, 'push');
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Velocity';
            app.text23 = uilabel(app.ViewEquationsResultsTab);
            app.text23.Tag = 'text23';
            app.text23.Text = 'View Equations';
            app.ResultsTextAreaLabel = uilabel(app.ViewEquationsResultsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ViewEquationsResultsTab);
            app.WriteDisplayResultsTableButton = uibutton(app.ViewEquationsResultsTab, 'push');
            app.WriteDisplayResultsTableButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDisplayResultsTableButtonPushed, true);
            app.WriteDisplayResultsTableButton.Text = 'Write & Display Results Table ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panel_point_force_multi_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end