classdef peak_sigma_random_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        text8                 matlab.ui.control.Label
        uipanel_results       matlab.ui.container.Panel
        text18                matlab.ui.control.Label
        edit_eq_duration      matlab.ui.control.EditField
        text6                 matlab.ui.control.Label
        edit_result           matlab.ui.control.EditField
        uipanel3              matlab.ui.container.Panel
        text17                matlab.ui.control.Label
        text16                matlab.ui.control.Label
        text15                matlab.ui.control.Label
        text14                matlab.ui.control.Label
        text_ax               matlab.ui.control.Label
        text_percent          matlab.ui.control.Label
        edit_ax               matlab.ui.control.EditField
        text10                matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        listbox_method        matlab.ui.control.ListBox
        text4                 matlab.ui.control.Label
        edit_fn               matlab.ui.control.EditField
        text5                 matlab.ui.control.Label
        edit_T                matlab.ui.control.EditField
        pushbutton_return     matlab.ui.control.Button
        pushbutton_calculate  matlab.ui.control.Button
        text3                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.listbox_method.scroll(app.listbox_method.Items{3});
        end
        
        function listbox(app)

            app.uipanel_results.Visible='off';

            Items = char(app.listbox_method.Items);
            
            im=app.listbox_method.Value;

            [nn]=find_index(Items,im);

            
            app.edit_ax.Visible='off';
            app.text_ax.Visible='off';
            app.text_percent.Visible='off';
            
            if(nn>=2)
                
                app.edit_ax.Visible='on';
                app.edit_ax.Enable='on';
                app.text_ax.Visible='on';
                app.text_percent.Visible='on';
            end
            
            if(nn==2)
                app.edit_ax.Value='63.2';
            end    
            if(nn==3)
                app.edit_ax.Value='50';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function peak_sigma_random_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to peak_sigma_random (see VARARGIN)
            
            % Choose default command line output for peak_sigma_random
            handles.output = hObject;
            
            listbox(app);

            %   listbox_method_Callback(hObject, eventdata, handles);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_method
        function listbox_method_Callback(app, event)
            listbox(app);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            im=get(handles.listbox_method,'Value');
            
            fn=str2num(get(handles.edit_fn,'String'));
            T=str2num(get(handles.edit_T,'String'));
            
            [ps]=maximax_peak(fn,T);
            
            if(im>=2)
            
                ax=(1/100)*str2num(get(handles.edit_ax,'String'));
            
                [ps]=risk_overshoot(fn,T,ax);
            
            end
            
            
            %
            % disp(' ');
            % out1=sprintf(' The maximum expected peak response is: %4.3g sigma ',ps);
            % disp(out1);
            
            if(isinf(ps)==1)
                warndlg(' (fn T) product is too high for stable calculation ');
                return;
            end
            
            str=sprintf('%4.3g sigma',ps);
            
            set(handles.edit_result,'String',str);
            
            set(handles.uipanel_results,'Visible','on');
            
            %%%%%%
            
                tsec=T;
            
                tdays=floor(tsec/86400);
            %
                residual=tsec-tdays*86400;
            
                thr=floor(residual/3600);
            
                residual=residual-thr*3600;
            
                tmin=floor(residual/60);
            
                residual=residual-tmin*60;
            
                tsec=residual;
            
                if(tdays>=365)
                    years=tdays/365.25;
                    sss=sprintf('%10.4g years',years);
                    set(handles.edit_eq_duration,'String',sss);
                    return;
                end
            
                if(tdays>=1)
                    sss=sprintf('%d days %d hr %d min %g sec',tdays,thr,tmin,tsec);
                    set(handles.edit_eq_duration,'String',sss);
                    return;
                end
            
                if(thr>=1)
                    sss=sprintf('%g hr %g min %g sec',(24*tdays+thr),tmin,tsec);
                    set(handles.edit_eq_duration,'String',sss);
                    return;
                end
            
                if(tmin>=1)
                    sss=sprintf('%g min %g sec',tmin,tsec);
                    set(handles.edit_eq_duration,'String',sss);
                    return;
                end
            
                sss=sprintf('  %g sec',tsec);
                set(handles.edit_eq_duration,'String',sss);
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Value changing function: edit_fn
        function edit_fnValueChanging(app, event)
             app.uipanel_results.Visible='off';
        end

        % Value changing function: edit_T
        function edit_TValueChanging(app, event)
             app.uipanel_results.Visible='off';
        end

        % Value changing function: edit_ax
        function edit_axValueChanging(app, event)
             app.uipanel_results.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'peak_sigma_random';
            app.figure1.Tag = 'figure1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'peak_sigma_random.m  ver 1.7   by Tom Irvine      Consider a system subjected to a stationary random base excitation or an applied force.';
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Input';
            app.uipanel3.Tag = 'uipanel3';
            app.edit_T = uieditfield(app.uipanel3, 'text');
            app.edit_T.ValueChangingFcn = createCallbackFcn(app, @edit_TValueChanging, true);
            app.edit_T.Tag = 'edit_T';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Enter Duration (sec)';
            app.edit_fn = uieditfield(app.uipanel3, 'text');
            app.edit_fn.ValueChangingFcn = createCallbackFcn(app, @edit_fnValueChanging, true);
            app.edit_fn.Tag = 'edit_fn';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Enter Natural Frequency (Hz) ';
            app.listbox_method = uilistbox(app.uipanel3);
            app.listbox_method.Items = {'Standard, Maximax, ERS, Average Maximum', 'Risk of Overshoot, XRS, URS', 'Risk of Overshoot 50%'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_method_Callback, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Risk of Overshoot 50%';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Select Method';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = '(or positive slope  zero-crossing rate)';
            app.edit_ax = uieditfield(app.uipanel3, 'text');
            app.edit_ax.ValueChangingFcn = createCallbackFcn(app, @edit_axValueChanging, true);
            app.edit_ax.Tag = 'edit_ax';
            app.edit_ax.Value = '63.2';
            app.text_percent = uilabel(app.uipanel3);
            app.text_percent.Tag = 'text_percent';
            app.text_percent.Text = '%';
            app.text_ax = uilabel(app.uipanel3);
            app.text_ax.Tag = 'text_ax';
            app.text_ax.Text = 'Probability of Exceedance';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'ERS - Extreme Response Spectrum';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'URS - Up-Crossing Risk Spectrum ';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Text = 'XRS - X Response Spectrum';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Acronyms:';
            app.uipanel_results = uipanel(app.figure1);
            app.uipanel_results.Title = 'Results';
            app.uipanel_results.Tag = 'uipanel_results';
            app.edit_result = uieditfield(app.uipanel_results, 'text');
            app.edit_result.Tag = 'edit_result';
            app.text6 = uilabel(app.uipanel_results);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Maximum Expected Peak';
            app.edit_eq_duration = uieditfield(app.uipanel_results, 'text');
            app.edit_eq_duration.Tag = 'edit_eq_duration';
            app.text18 = uilabel(app.uipanel_results);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Equivalent Duration';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'This script calculates the maximum expected peak response via the Rayeigh distribution.';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = peak_sigma_random_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)peak_sigma_random_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end