classdef plot_1D_array < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataFourierTransform  matlab.ui.Figure
        OutputStatisticsTextArea       matlab.ui.control.TextArea
        OutputStatisticsTextAreaLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        MaximumEditField               matlab.ui.control.EditField
        MaximumEditFieldLabel          matlab.ui.control.Label
        MinimumEditField               matlab.ui.control.EditField
        MinimumEditFieldLabel          matlab.ui.control.Label
        AmplitudeLimitsListBox         matlab.ui.control.ListBox
        AmplitudeLimitsListBoxLabel    matlab.ui.control.Label
        FigureNumberListBox            matlab.ui.control.ListBox
        FigureNumberListBoxLabel       matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        XaxisLabelEditField_2Label     matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        ReturnButton                   matlab.ui.control.Button
        YaxisLabelEditField            matlab.ui.control.EditField
        TimeHistoryYaxisLabelEditFieldLabel  matlab.ui.control.Label
        plot_1D_arraybyTomIrvinever10Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM    % Time history
        TLn    % Description
        ALn    %
        msz    %
        kflag  %
        num    %
        xs     %
        ys     %
        tstring  %
        fig_num  %        
    end
    
    methods (Access = private)

        function clear(app)
            app.OutputStatisticsTextArea.Visible='off';
            app.OutputStatisticsTextAreaLabel.Visible='off';
        end    
        
        function plot_input(app)
            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                        
 
            app.ys=app.YaxisLabelEditField.Value;
            app.tstring=app.TitleEditField.Value;
            app.fig_num=str2num(app.FigureNumberListBox.Value);
                       
        end    

        function ClosePlots(app)
            clear(app);
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end

        
        function update(app)

            clear(app);

            [cell_array]=update_1D_array();

            app.kflag=0;  

            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method.Value=app.listbox_method.Items{1};
        end
        
        function TL(app)
            clear(app);

            Items = char(app.AmplitudeLimitsListBox.Items);
            im=app.AmplitudeLimitsListBox.Value;
            [m]=find_index(Items,im);  

            if(m==1)
                app.MinimumEditField.Visible='off';
                app.MinimumEditFieldLabel.Visible='off';
                app.MaximumEditField.Visible='off';
                app.MaximumEditFieldLabel.Visible='off';
            else
                app.MinimumEditField.Visible='on';
                app.MinimumEditFieldLabel.Visible='on';
                app.MaximumEditField.Visible='on';
                app.MaximumEditFieldLabel.Visible='on';            
            end

            app.ALn=m;
            

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            TL(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            ClosePlots(app);

            plot_input(app);
          
            hfig=figure(app.fig_num);
            plot(app.THM);
            ylabel(app.ys);
            title(app.tstring);
            grid on;

            amp=app.THM;

            if(app.ALn==2)
                ymin=str2num(app.MinimumEditField.Value);
                ymax=str2num(app.MaximumEditField.Value);
                ylim([ymin ymax]);
            end
 
 %           
            n = length(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         
            big_string=sprintf(' %d samples ',n);
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Calculating skewness & kurtosis ');
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%      
            
            string_amp=sprintf('\n\n Amplitude Stats ');
            string2=sprintf('\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=strcat(big_string,string_amp);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
                        
                mx=max(amp);
                mi=min(amp);
            
                amax=abs(mx);
                amin=abs(mi);
                if(amax<amin)
                    amax=amin;
                end
                crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);
            
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            
            app.OutputStatisticsTextArea.Value=big_string;
            app.OutputStatisticsTextArea.Visible='on';
            app.OutputStatisticsTextAreaLabel.Visible='on';
            
        end

        % Callback function
        function SignalProcessingListBoxValueChanged(app, event)

        end

        % Callback function
        function CalculateFourierTransformButtonPushed(app, event)

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g',[1 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Callback function: not associated with a component
        function TimeLimitsListBoxValueChanged(app, event)
            TL(app);
        end

        % Value changed function: AmplitudeLimitsListBox
        function AmplitudeLimitsListBoxValueChanged(app, event)
            TL(app);
        end

        % Callback function: not associated with a component
        function PlotFirstColumnOnlyButtonPushed(app, event)
            plot_input(app);

            clear(app);
          
            hfig=figure(app.fig_num);
            plot(app.THM(:,1));
            ylabel(app.xs);
            title(app.tstring);
            grid on;

            if(app.TLn==2)
                t1=str2num(app.StartTimesecEditField.Value);
                t2=str2num(app.EndTimesecEditField.Value);
                ylim([t1 t2]);
            end

        end

        % Callback function: not associated with a component
        function StartTimesecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Callback function: not associated with a component
        function EndTimesecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            value = app.SelectInputArrayListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataFourierTransform = uifigure('Visible', 'off');
            app.UIVibrationdataFourierTransform.Name = 'Plot 1D Array';
            app.plot_1D_arraybyTomIrvinever10Label = uilabel(app.UIVibrationdataFourierTransform);
            app.plot_1D_arraybyTomIrvinever10Label.Text = 'plot_1D_array   by Tom Irvine   ver 1.0';
            app.TimeHistoryYaxisLabelEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TimeHistoryYaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.ReturnButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.listbox_method = uilistbox(app.UIVibrationdataFourierTransform);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIVibrationdataFourierTransform);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.XaxisLabelEditField_2Label = uilabel(app.UIVibrationdataFourierTransform);
            app.XaxisLabelEditField_2Label.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.FigureNumberListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.FigureNumberListBoxLabel.Text = 'Figure Number';
            app.FigureNumberListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.FigureNumberListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.FigureNumberListBox.Value = '1';
            app.AmplitudeLimitsListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.AmplitudeLimitsListBoxLabel.Text = 'Amplitude Limits';
            app.AmplitudeLimitsListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.AmplitudeLimitsListBox.Items = {'Automatic', 'Manual'};
            app.AmplitudeLimitsListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeLimitsListBoxValueChanged, true);
            app.AmplitudeLimitsListBox.Value = 'Automatic';
            app.MinimumEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.MaximumEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.PlotButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.OutputStatisticsTextAreaLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.OutputStatisticsTextAreaLabel.Text = 'Output Statistics';
            app.OutputStatisticsTextArea = uitextarea(app.UIVibrationdataFourierTransform);
            app.OutputStatisticsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIVibrationdataFourierTransform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_1D_array

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIVibrationdataFourierTransform)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIVibrationdataFourierTransform)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataFourierTransform)
        end
    end
end