classdef plot_time_history_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataFourierTransform  matlab.ui.Figure
        OutputStatisticsTextArea       matlab.ui.control.TextArea
        OutputStatisticsTextAreaLabel  matlab.ui.control.Label
        PlotFirstColumnOnlyButton      matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        MaximumEditField               matlab.ui.control.EditField
        MaximumEditFieldLabel          matlab.ui.control.Label
        MinimumEditField               matlab.ui.control.EditField
        MinimumEditFieldLabel          matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.EditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.EditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        AmplitudeLimitsListBox         matlab.ui.control.ListBox
        AmplitudeLimitsListBoxLabel    matlab.ui.control.Label
        TimeLimitsListBox              matlab.ui.control.ListBox
        TimeLimitsListBoxLabel         matlab.ui.control.Label
        FigureNumberListBox            matlab.ui.control.ListBox
        FigureNumberListBoxLabel       matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        XaxisLabelEditField_2Label     matlab.ui.control.Label
        XaxisLabelEditField            matlab.ui.control.EditField
        YaxisLabelEditField_2Label     matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        ReturnButton                   matlab.ui.control.Button
        YaxisLabelEditField            matlab.ui.control.EditField
        TimeHistoryYaxisLabelEditFieldLabel  matlab.ui.control.Label
        plot_time_history_ahbyTomIrvinever16Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM    % Time history
        TLn    % Description
        ALn    %
        msz    %
        kflag  %
        num    %
        xs     %
        ys     %
        tstring  %
        fig_num  %        
    end
    
    methods (Access = private)

        function clear(app)
            app.OutputStatisticsTextArea.Visible='off';
            app.OutputStatisticsTextAreaLabel.Visible='off';
        end    
        
        function plot_input(app)
            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                        
            app.xs=app.XaxisLabelEditField.Value;
            app.ys=app.YaxisLabelEditField.Value;
            app.tstring=app.TitleEditField.Value;
            app.fig_num=str2num(app.FigureNumberListBox.Value);
                       
        end    

        function ClosePlots(app)
            clear(app);
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end

        
        function update(app)

            clear(app);

            [cell_array]=update_two_columns();

            app.kflag=0;  

            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method.Value=app.listbox_method.Items{1};
        end
        
        function TL(app)
            clear(app);

            Items = char(app.TimeLimitsListBox.Items);
            im=app.TimeLimitsListBox.Value;
            [n]=find_index(Items,im);  

            if(n==1)
                app.StartTimesecEditField.Visible='off';
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
            else
                app.StartTimesecEditField.Visible='on';
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';            
            end

            app.TLn=n;

            Items = char(app.AmplitudeLimitsListBox.Items);
            im=app.AmplitudeLimitsListBox.Value;
            [m]=find_index(Items,im);  

            if(m==1)
                app.MinimumEditField.Visible='off';
                app.MinimumEditFieldLabel.Visible='off';
                app.MaximumEditField.Visible='off';
                app.MaximumEditFieldLabel.Visible='off';
            else
                app.MinimumEditField.Visible='on';
                app.MinimumEditFieldLabel.Visible='on';
                app.MaximumEditField.Visible='on';
                app.MaximumEditFieldLabel.Visible='on';            
            end

            app.ALn=m;
            

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            TL(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            ClosePlots(app);

            plot_input(app);
          
            hfig=figure(app.fig_num);
            plot(app.THM(:,1),app.THM(:,2));
            xlabel(app.xs);
            ylabel(app.ys);
            title(app.tstring);
            grid on;

            t=app.THM(:,1);
            amp=app.THM(:,2);

            if(app.TLn==2)
                t1=str2num(app.StartTimesecEditField.Value);
                t2=str2num(app.EndTimesecEditField.Value);
                xlim([t1 t2]);

                [~,ii]=min(abs(t-t1));
                [~,jj]=min(abs(t-t2));

                t=t(ii:jj);
                amp=amp(ii:jj);              
            end

            if(app.ALn==2)
                ymin=str2num(app.MinimumEditField.Value);
                ymax=str2num(app.MaximumEditField.Value);
                ylim([ymin ymax]);
            end
 %           
            n = length(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                tim=t;
                duration=tim(n)-tim(1);
                dt=duration/(n-1);
                difft=diff(t);
                dtmin=min(difft);
                dtmax=max(difft);
            %
                outa = sprintf('\n start  = %8.4g sec  ',t(1));
                outb = sprintf('\n end    = %8.4g sec  ',t(n));
            
                out4 = sprintf('\n dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf('\n dt     = %8.4g sec  ',dt);
                out6 = sprintf('\n dtmax  = %8.4g sec  ',dtmax);
            
            %
                out7 = sprintf('\n srmin  = %8.4g samples/sec  ',1/dtmax);
                out8 = sprintf('\n sr     = %8.4g samples/sec  ',1/dt);
                out9 = sprintf('\n srmax  = %8.4g samples/sec  ',1/dtmin);
            
            string1=sprintf(' %d samples ',n);
            
            string_tss=sprintf('\n\n Time Limits ');
            
            big_string=strcat(string1,string_tss);
            big_string=strcat(big_string,outa);
            big_string=strcat(big_string,outb);
            
            string_ts=sprintf('\n\n Time Step ');
            
            big_string=strcat(big_string,string_ts);
            big_string=strcat(big_string,out4);
            big_string=strcat(big_string,out5);
            big_string=strcat(big_string,out6);
            
            string_sr=sprintf('\n\n Sample Rate ');
            big_string=strcat(big_string,string_sr);
            big_string=strcat(big_string,out7);
            big_string=strcat(big_string,out8);
            big_string=strcat(big_string,out9);
            
            disp(big_string);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Calculating skewness & kurtosis ');
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Calculating Zero & Peak crossings ');
            
            [pszcr,peak_rate,tpa,pa]=zero_crossing_function_alt(tim,amp,duration);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            string_amp=sprintf('\n\n Amplitude Stats ');
            string2=sprintf('\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=strcat(big_string,string_amp);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
                        
                mx=max(amp);
                mi=min(amp);
            
                amax=abs(mx);
                amin=abs(mi);
                if(amax<amin)
                    amax=amin;
                end
                crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);
            
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            
            [rf]=Rice_frequency(amp,dt);
            
            %
                string_rf=sprintf('\n\n Rice Characteristic Frequency = %8.4g Hz ',rf);
            
                string_zc=sprintf('\n Positive Slope Zero Cross Rate = %8.4g Hz ',pszcr);
                string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            disp(string_rf);
            disp(string_zc);
            disp(string_pr);
            
            big_string=strcat(big_string,string_rf);
            big_string=strcat(big_string,string_zc);
            big_string=strcat(big_string,string_pr);
            
            app.OutputStatisticsTextArea.Value=big_string;
            app.OutputStatisticsTextArea.Visible='on';
            app.OutputStatisticsTextAreaLabel.Visible='on';
            
        end

        % Callback function
        function SignalProcessingListBoxValueChanged(app, event)

        end

        % Callback function
        function CalculateFourierTransformButtonPushed(app, event)

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);

               try
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    

        end

        % Value changed function: TimeLimitsListBox
        function TimeLimitsListBoxValueChanged(app, event)
            TL(app);
        end

        % Value changed function: AmplitudeLimitsListBox
        function AmplitudeLimitsListBoxValueChanged(app, event)
            TL(app);
        end

        % Button pushed function: PlotFirstColumnOnlyButton
        function PlotFirstColumnOnlyButtonPushed(app, event)
            plot_input(app);

            clear(app);
          
            hfig=figure(app.fig_num);
            plot(app.THM(:,1));
            ylabel(app.xs);
            title(app.tstring);
            grid on;

            if(app.TLn==2)
                t1=str2num(app.StartTimesecEditField.Value);
                t2=str2num(app.EndTimesecEditField.Value);
                ylim([t1 t2]);
            end

        end

        % Value changing function: StartTimesecEditField
        function StartTimesecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: EndTimesecEditField
        function EndTimesecEditFieldValueChanging(app, event)
            clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataFourierTransform = uifigure('Visible', 'off');
            app.UIVibrationdataFourierTransform.Name = 'UI Plot Time History';
            app.plot_time_history_ahbyTomIrvinever16Label = uilabel(app.UIVibrationdataFourierTransform);
            app.plot_time_history_ahbyTomIrvinever16Label.Text = 'plot_time_history_ah   by Tom Irvine   ver 1.6';
            app.TimeHistoryYaxisLabelEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TimeHistoryYaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.ReturnButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.listbox_method = uilistbox(app.UIVibrationdataFourierTransform);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIVibrationdataFourierTransform);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.YaxisLabelEditField_2Label = uilabel(app.UIVibrationdataFourierTransform);
            app.YaxisLabelEditField_2Label.Text = 'X-axis Label';
            app.XaxisLabelEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.XaxisLabelEditField.Value = 'Time (sec)';
            app.XaxisLabelEditField_2Label = uilabel(app.UIVibrationdataFourierTransform);
            app.XaxisLabelEditField_2Label.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.FigureNumberListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.FigureNumberListBoxLabel.Text = 'Figure Number';
            app.FigureNumberListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.FigureNumberListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.FigureNumberListBox.Value = '1';
            app.TimeLimitsListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TimeLimitsListBoxLabel.Text = 'Time Limits';
            app.TimeLimitsListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.TimeLimitsListBox.Items = {'Automatic', 'Manual'};
            app.TimeLimitsListBox.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsListBoxValueChanged, true);
            app.TimeLimitsListBox.Value = 'Automatic';
            app.AmplitudeLimitsListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.AmplitudeLimitsListBoxLabel.Text = 'Amplitude Limits';
            app.AmplitudeLimitsListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.AmplitudeLimitsListBox.Items = {'Automatic', 'Manual'};
            app.AmplitudeLimitsListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeLimitsListBoxValueChanged, true);
            app.AmplitudeLimitsListBox.Value = 'Automatic';
            app.StartTimesecEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.StartTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @StartTimesecEditFieldValueChanging, true);
            app.EndTimesecEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.EndTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @EndTimesecEditFieldValueChanging, true);
            app.MinimumEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.MaximumEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.PlotButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotFirstColumnOnlyButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.PlotFirstColumnOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFirstColumnOnlyButtonPushed, true);
            app.PlotFirstColumnOnlyButton.Text = 'Plot First Column Only';
            app.OutputStatisticsTextAreaLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.OutputStatisticsTextAreaLabel.Text = 'Output Statistics';
            app.OutputStatisticsTextArea = uitextarea(app.UIVibrationdataFourierTransform);
            app.OutputStatisticsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIVibrationdataFourierTransform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_time_history_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIVibrationdataFourierTransform)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIVibrationdataFourierTransform)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataFourierTransform)
        end
    end
end