classdef power_transmissibility_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerTransmissibilityUIFigure  matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        DataEntryTab                   matlab.ui.container.Tab
        ResponseYaxisEditField         matlab.ui.control.EditField
        ResponseYaxisEditFieldLabel    matlab.ui.control.Label
        InputYaxisEditField            matlab.ui.control.EditField
        InputYaxisEditFieldLabel       matlab.ui.control.Label
        SelectResponseInputArrayListBox  matlab.ui.control.ListBox
        SelectResponseInputArrayListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1_2                        matlab.ui.control.Label
        ListboxFormat                  matlab.ui.control.ListBox
        text1                          matlab.ui.control.Label
        ListboxMethod                  matlab.ui.control.ListBox
        ApplyTimeLimitsButton          matlab.ui.control.Button
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        ReadDataButton                 matlab.ui.control.Button
        CalculateTransTab              matlab.ui.container.Tab
        HanningisbestchoiceforTransmissibilityLabel  matlab.ui.control.Label
        PeakResultsEditField           matlab.ui.control.EditField
        PeakResultsEditFieldLabel      matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        WindowListBox                  matlab.ui.control.ListBox
        WindowListBoxLabel             matlab.ui.control.Label
        MeanRemovalListBox             matlab.ui.control.ListBox
        MeanRemovalListBoxLabel        matlab.ui.control.Label
        ProcessingOptionsLabel         matlab.ui.control.Label
        SelectdofListBox               matlab.ui.control.ListBox
        SelectdofListBoxLabel          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CalculateButton                matlab.ui.control.Button
        ExportTransTab                 matlab.ui.container.Tab
        HalfPowerBandwidthExtensiontoExtractQButton  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        FunctionListBox                matlab.ui.control.ListBox
        FunctionListBoxLabel           matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM     % time history array
        ttt     % time column
        amp     % amplitude column
        s1      % amplitude dimension
        s2      % amplitude unitf
        dt      % time step
        df      % frequency step
        num     % number of points 
        nhalf   % half the number of points
        AA      % signal processing option array
        fig_num % figure number 
        Fourier_magnitude       % 
        power_spectral_density  % 
        fmin %
        fmax %
        rms  %
        x_label %
        y_label %
        N %
        iflag %
        ylabel1 %
        ylabel2 %
        psd_y1  %
        psd_y2  %
        YS_input     %
        YS_response  %
        trans_ylabel %
        a_power_spectral_density %
        b_power_spectral_density %
        power_trans  %
    end
    
    methods (Access = private)

        function external_array(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end

        end    

        function TH_plot(app)
 
            xlabel2='Time (sec)';
            t_string1='Input';
            t_string2='Response';
            data1=[app.THM(:,1) app.THM(:,2)];
            data2=[app.THM(:,1) app.THM(:,3)];

            if(contains(app.ylabel1,app.ylabel2))
                [app.fig_num]=subplots_two_linlin_two_titles_scale(app.fig_num,xlabel2,app.ylabel1,app.ylabel2,data1,data2,t_string1,t_string2);
            else 
                [app.fig_num]=subplots_two_linlin_two_titles(app.fig_num,xlabel2,app.ylabel1,app.ylabel2,data1,data2,t_string1,t_string2);
            end
        end    

        function Choice(app)

            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';

            app.num=length(app.THM(:,1));
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.THM(:,2);
            app.ttt=app.THM(:,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            app.N=floor(log(app.num)/log(2));
            
            if(app.N>12)
                app.N=12;
            end
            
            ss={'2','4','8','16','32','64','128','256','512','1024','2048','4096'};            
            sq={'1','2','3', '4', '5', '6',  '7',  '8',  '9',  '10',  '11', '12'};            
             
            A=zeros(app.N,5);
            
            for i=1:app.N
                m=2^(i-1); 
                A(i,1)=m;      
                A(i,2)=floor(app.num/m);
                A(i,3)=A(i,2)*app.dt;
                A(i,4)=1/A(i,3);
                A(i,5)=2*A(i,1);
            end  
            
            myData=array2table(A);
            app.UITable.Data=myData;  
            
            for i=10:-1:app.N
                ss(end)=[];
                sq(end)=[];                
            end
  
            app.SelectdofListBox.Items=ss;
            % app.UITable.RowName=sq;
            
            app.AA=A;
        end            

       function Trans_change(app)

            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            app.SelectInputArrayListBox.Visible='off';
            app.SelectInputArrayListBoxLabel.Visible='off';   
            app.SelectResponseInputArrayListBox.Visible='off';
            app.SelectResponseInputArrayListBoxLabel.Visible='off';  

            if(n==2)
                external_array(app);
            end    

            app.SelectInputArrayListBox.Visible='on';
            app.SelectInputArrayListBoxLabel.Visible='on'; 
                
            if(m==1)
                ss=sprintf('Select Common Input Array');
            else
                ss=sprintf('Select Base Input Array');
                app.SelectResponseInputArrayListBox.Visible='on';
                app.SelectResponseInputArrayListBoxLabel.Visible='on';                
            end

            app.SelectInputArrayListBoxLabel.Text=ss; 
            update(app);

            app.ListboxMethod.Value=app.ListboxMethod.Items{1};

        end        
        
        function Signal(app)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
                app.ApplyTimeLimitsButton.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.ApplyTimeLimitsButton.Visible='on';         
            end
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            cell_array=update_time_history_23();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.SelectResponseInputArrayListBox.Items=unique(sort(cell_array));             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Signal(app);
            update(app);
            Trans_change(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            ClosePlots(app);

            app.fig_num=1;
            
            app.ylabel1=strtrim(app.InputYaxisEditField.Value);
            app.ylabel2=strtrim(app.ResponseYaxisEditField.Value);

            if(~contains(app.ylabel1,'(') || ~contains(app.ylabel2,'(') )
                warndlg('Y-axis labels must contain parenthesis such as ''(unit)''');
                return;
            end    
            if(~contains(app.ylabel1,')') || ~contains(app.ylabel2,')') )
                warndlg('Y-axis labels must contain parenthesis such as ''(unit)''');
                return;
            end   

            try
                k1 = regexp(app.ylabel1, '(');
                k2 = regexp(app.ylabel1, ')');
                k1=min(k1)+1;
                k2=max(k2)-1;
                u1=char(extractBetween(app.ylabel1,k1,k2));
                us=sprintf('%s^2/Hz',u1);
                app.psd_y1=strrep(app.ylabel1,u1,us);
            catch
            end 

            try
                k1 = regexp(app.ylabel1, '(');
                k2 = regexp(app.ylabel1, ')');
                k1=min(k1)+1;
                k2=max(k2)-1;
                u2=char(extractBetween(app.ylabel2,k1,k2));
                us=sprintf('%s^2/Hz',u2);
                app.psd_y2=strrep(app.ylabel2,u2,us);
                app.trans_ylabel=sprintf('Trans (%s^2/%s^2)',u2,u1);
            catch
            end              

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            if(n==1)

                if(m==1)
                    try
                        FS=app.SelectInputArrayListBox.Value;
                        app.THM=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end
                else

                    try
                        FS=app.SelectInputArrayListBox.Value;
                        F1=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end

                    try
                        FS=app.SelectResponseInputArrayListBox.Value;
                        F2=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end                    
   
                    try
                        app.THM=[F1 F2(:,2)];
                    catch
                        size(F1)
                        size(F2)
                        warndlg('Arrays not compatible');
                        return;
                    end    

                end

            else
                if(m==1)
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       app.THM = fscanf(fid,'%g %g %g',[2 inf]);
                       app.THM=app.THM';
                
                       size(app.THM)
    
                       msgbox('File read');              
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end
                else    
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F1 = fscanf(fid,'%g %g',[2 inf]);
                       F1=F1';
                
                       sz1=size(F1);
    
                       msgbox('File 1 read'); 

                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F2 = fscanf(fid,'%g %g',[2 inf]);
                       F2=F2';
                
                       sz2=size(F2);
    
                       msgbox('File 2 read');

                       if(sz1(1)~=sz2(1))
                            warndlg('File 1 & File 2 have different lengths')
                            return;
                       else    
                            app.THM=[F1 F2(:,2)];
                       end     
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end                
                end
            end           

            try
                TH_plot(app);
            catch
                warndlg('Time history plot failed');
                return;
            end

            Choice(app);

            msgbox('Apply Time Limits Or Go to Calculate Trans tab');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            if(app.iflag==999)
                return;
            end

            fprintf('\n\n * * * * * * * * \n\n');
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end
            if(max(size(app.THM))<=1)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end           
            if(app.N==0)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end               
        
            b=app.SelectdofListBox.Value;
            for i=1:app.N
                a=2^i;
                ss=sprintf('%d',a);
                
                if(strcmp(b,ss))
                    % dof=a;
                    num_seg=app.AA(i,1);
                    samples_seg=app.AA(i,2);
                    app.df=app.AA(i,4);
                    break;
                end
            end  
            
            NW=num_seg;
            
            mmm=samples_seg;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            mmr=app.MeanRemovalListBox.Value;
            
            if(strcmp(mmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            h_choice = find(strcmp(Items, im));

            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            a=app.THM(:,2);
            b=app.THM(:,3);
           
             [~,a_full,a_rms]=psd_core(mmm,NW,mr_choice,h_choice,a,app.df);
            [freq,b_full,b_rms]=psd_core(mmm,NW,mr_choice,h_choice,b,app.df);
            
            %
                freq=fix_size(freq);
                a_full=fix_size(a_full);
                b_full=fix_size(b_full);    
            %
                app.a_power_spectral_density=[freq a_full]; 
                app.b_power_spectral_density=[freq b_full];     
                    
            %          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            if(contains(app.ylabel1,'(G)'))
                t_string1=sprintf(' Input %7.3g GRMS',a_rms);
            else
                Y1=strrep(app.YS_input,'^2','');
                t_string1=sprintf(' Input %7.3g %s RMS',a_rms,Y1);
            end
            
            if(contains(app.ylabel2,'(G)'))
                t_string2=sprintf(' Response %7.3g GRMS',b_rms);   
            else
                Y2=strrep(app.YS_response,'^2','');
                t_string2=sprintf(' Response %7.3g %s RMS',b_rms,Y2);    
            end
                
            %%%%%%%%%%%%%%%%%%%%
            
            md=5;
            app.x_label='Frequency (Hz)';
            
            ppp1=app.a_power_spectral_density;
            ppp2=app.b_power_spectral_density;
            
            leg1=t_string1;
            leg2=t_string2;
          
            if(contains(app.ylabel1,app.ylabel2))
 
                t_string='Power Spectral Density';
            
                [app.fig_num,~]=plot_loglog_function_md_two_h2(app.fig_num,app.x_label,...
                   app.psd_y1,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);
                
            else
                nfont=10;
                data1=ppp1;
                data2=ppp2;
                
                t_string1=sprintf('PSD  %s ',t_string1);
                t_string2=sprintf('PSD  %s ',t_string2);
                
                xlabel2='Frequency (Hz)';
                
                [app.fig_num,~]=subplots_two_loglog_1x2_xlim_h2(app.fig_num,xlabel2,...
                      app.psd_y1,app.psd_y2,data1,data2,t_string1,t_string2,nfont,app.fmin,app.fmax);
            end
                       
            %%%%%%%%%%%%%%%%%%%%
            
            %%  app.a_power_spectral_density=[freq a_full]; 
            %%  app.b_power_spectral_density=[freq b_full]; 
                        
            ptrans=b_full./a_full;
            
            app.power_trans=[freq ptrans];
            setappdata(0,'power_trans',app.power_trans); 
            
            trans(:,1)=freq;
            
            for i=1:length(freq)
               trans(i,2)=sqrt(ptrans(i)); 
            end
            
            setappdata(0,'trans',trans);
            
            ppp=app.power_trans;
            
            t_string='Power Transmissibility';
              
            [app.fig_num]=plot_loglog_function_md(app.fig_num,app.x_label,app.trans_ylabel,t_string,ppp,app.fmin,app.fmax,md);
            
                [aaa,app.fmax]=find_max(app.power_trans);
            %
                LLL=strrep(app.trans_ylabel,'Trans','')
                ss = sprintf(' %7.3g %s at %10.5g Hz \n',aaa,LLL,app.fmax);
                app.PeakResultsEditField.Value=ss;
            
                app.PeakResultsEditField.Visible='on';
                app.PeakResultsEditFieldLabel.Visible='on';
                   
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                   
            Items = char(app.FunctionListBox.Items);
            im=app.FunctionListBox.Value;
            [n]=find_index(Items,im); 

            if(n==1)
                data=app.power_trans;
            end    
            if(n==2)
                L=length(app.power_trans(:,1));

                t=app.power_trans(:,1);
                a=app.power_trans(:,2);
 
                for i=1:L
                    a(i)=sqrt(app.power_trans(i,2));
                end
                data=[t a];
            end  
            if(n==3)
                data=app.a_power_spectral_density;
            end    
            if(n==4)
                data=app.b_power_spectral_density;
            end  
            
            name=app.OutputArrayNameEditField.Value;
            
            assignin('base',name, data); 

            msgbox('Save Complete');
            
        end

        % Callback function
        function PlotButtonPushed(app, event)

            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end
        
            FS = app.InputSpecificationArrayNameEditField.Value;
            try
                STHM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end  
        
            [~,srms] = calculate_PSD_slopes_no(STHM(:,1),STHM(:,2));

            ppp=app.power_spectral_density;
            qqq=STHM; 
            
   
            leg_b=sprintf('input %6.3g %sRMS',app.rms,app.s2);
            leg_a=sprintf('spec  %6.3g %sRMS',srms,app.s2);
            
            t_string='Power Spectral Density';
            
            pname='';
            nps=2;

            [app.fig_num]=plot_PSD_two_f(app.fig_num,app.x_label,app.y_label,t_string,qqq,ppp,leg_a,leg_b,app.fmin,app.fmax,pname,nps);
                
        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
        
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button down function: CalculateTransTab
        function CalculateTransTabButtonDown(app, event)
            if(app.N>=1)
            else    
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end  
        end

        % Callback function
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Callback function
        function listbox_methodValueChanged(app, event)

            update(app);
            
        end

        % Button pushed function: ApplyTimeLimitsButton
        function ApplyTimeLimitsButtonPushed(app, event)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Segment'))
                tstart=app.StartTimesecEditField.Value;
                tend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);    

                TH_plot(app);

                msgbox('Go to Calculate Trans tab');
            end
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed2(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxMethod
        function ListboxMethodValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat
        function ListboxFormatValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: SelectdofListBox
        function SelectdofListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Value changed function: MeanRemovalListBox
        function MeanRemovalListBoxValueChanged(app, event)
            app.PeakResultsEditField.Visible='off';
            app.PeakResultsEditFieldLabel.Visible='off';
        end

        % Button pushed function: 
        % HalfPowerBandwidthExtensiontoExtractQButton
        function HalfPowerBandwidthExtensiontoExtractQButtonPushed(app, event)
            damping_functions_a
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerTransmissibilityUIFigure = uifigure('Visible', 'off');
            app.PowerTransmissibilityUIFigure.Name = 'Power Transmissibility';
            app.Label = uilabel(app.PowerTransmissibilityUIFigure);
            app.Label.Text = 'power_transmissibility_ah   ver 1.7   by Tom Irvine   This script calculates the power transmissibility of two time histories.  ';
            app.TabGroup = uitabgroup(app.PowerTransmissibilityUIFigure);
            app.DataEntryTab = uitab(app.TabGroup);
            app.DataEntryTab.Title = 'Data Entry';
            app.ReadDataButton = uibutton(app.DataEntryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.SignalProcessingListBoxLabel = uilabel(app.DataEntryTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.DataEntryTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.ApplyTimeLimitsButton = uibutton(app.DataEntryTab, 'push');
            app.ApplyTimeLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTimeLimitsButtonPushed, true);
            app.ApplyTimeLimitsButton.Text = 'Apply Time Limits';
            app.ListboxMethod = uilistbox(app.DataEntryTab);
            app.ListboxMethod.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod.ValueChangedFcn = createCallbackFcn(app, @ListboxMethodValueChanged, true);
            app.ListboxMethod.Tag = 'listbox_method';
            app.ListboxMethod.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.DataEntryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.ListboxFormat = uilistbox(app.DataEntryTab);
            app.ListboxFormat.Items = {'Common array:  time(sec) & amplitude & amplitude', 'Two separate arrays, each:  time(sec) & amplitude'};
            app.ListboxFormat.ValueChangedFcn = createCallbackFcn(app, @ListboxFormatValueChanged, true);
            app.ListboxFormat.Tag = 'listbox_method';
            app.ListboxFormat.Value = 'Two separate arrays, each:  time(sec) & amplitude';
            app.text1_2 = uilabel(app.DataEntryTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Format';
            app.UpdateListButton = uibutton(app.DataEntryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed2, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SelectResponseInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectResponseInputArrayListBoxLabel.Text = 'Select Response Input Array';
            app.SelectResponseInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectResponseInputArrayListBox.Items = {};
            app.SelectResponseInputArrayListBox.Value = {};
            app.InputYaxisEditFieldLabel = uilabel(app.DataEntryTab);
            app.InputYaxisEditFieldLabel.Text = 'Input Y-axis';
            app.InputYaxisEditField = uieditfield(app.DataEntryTab, 'text');
            app.InputYaxisEditField.Value = 'Accel (G)';
            app.ResponseYaxisEditFieldLabel = uilabel(app.DataEntryTab);
            app.ResponseYaxisEditFieldLabel.Text = 'Response Y-axis';
            app.ResponseYaxisEditField = uieditfield(app.DataEntryTab, 'text');
            app.ResponseYaxisEditField.Value = 'Accel (G)';
            app.CalculateTransTab = uitab(app.TabGroup);
            app.CalculateTransTab.Title = 'Calculate Trans';
            app.CalculateTransTab.ButtonDownFcn = createCallbackFcn(app, @CalculateTransTabButtonDown, true);
            app.CalculateButton = uibutton(app.CalculateTransTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.CalculateTransTab);
            app.UITable.ColumnName = {'No. of Segments'; 'Samples/Segments'; 'Time/Segment (sec)'; 'df (Hz)'; 'dof'};
            app.UITable.RowName = {};
            app.SelectdofListBoxLabel = uilabel(app.CalculateTransTab);
            app.SelectdofListBoxLabel.Text = 'Select dof';
            app.SelectdofListBox = uilistbox(app.CalculateTransTab);
            app.SelectdofListBox.ValueChangedFcn = createCallbackFcn(app, @SelectdofListBoxValueChanged, true);
            app.ProcessingOptionsLabel = uilabel(app.CalculateTransTab);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.MeanRemovalListBoxLabel = uilabel(app.CalculateTransTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.CalculateTransTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.ValueChangedFcn = createCallbackFcn(app, @MeanRemovalListBoxValueChanged, true);
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.CalculateTransTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.CalculateTransTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Hanning';
            app.PlotFrequenciesHzLabel = uilabel(app.CalculateTransTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.CalculateTransTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculateTransTab, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.CalculateTransTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculateTransTab, 'numeric');
            app.MaxEditField.Value = 2000;
            app.PeakResultsEditFieldLabel = uilabel(app.CalculateTransTab);
            app.PeakResultsEditFieldLabel.Text = 'Peak Results';
            app.PeakResultsEditField = uieditfield(app.CalculateTransTab, 'text');
            app.HanningisbestchoiceforTransmissibilityLabel = uilabel(app.CalculateTransTab);
            app.HanningisbestchoiceforTransmissibilityLabel.Text = 'Hanning is best choice for Transmissibility';
            app.ExportTransTab = uitab(app.TabGroup);
            app.ExportTransTab.Title = 'Export Trans';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTransTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTransTab, 'text');
            app.FunctionListBoxLabel = uilabel(app.ExportTransTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTransTab);
            app.FunctionListBox.Items = {'Power Transmissibility', 'Transmissibility', 'Input PSD', 'Response PSD'};
            app.FunctionListBox.Value = 'Power Transmissibility';
            app.SaveButton = uibutton(app.ExportTransTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HalfPowerBandwidthExtensiontoExtractQButton = uibutton(app.ExportTransTab, 'push');
            app.HalfPowerBandwidthExtensiontoExtractQButton.ButtonPushedFcn = createCallbackFcn(app, @HalfPowerBandwidthExtensiontoExtractQButtonPushed, true);
            app.HalfPowerBandwidthExtensiontoExtractQButton.Text = {'Half Power Bandwidth '; 'Extension to Extract Q'};
            app.ReturnButton = uibutton(app.PowerTransmissibilityUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.PowerTransmissibilityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = power_transmissibility_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PowerTransmissibilityUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PowerTransmissibilityUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerTransmissibilityUIFigure)
        end
    end
end