classdef psd_envelope_via_fds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MsgEditField                    matlab.ui.control.EditField
        MsgEditFieldLabel               matlab.ui.control.Label
        LoadModelButton_env             matlab.ui.control.Button
        SaveModelButton_env             matlab.ui.control.Button
        LoadModelButton_env_2           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        NumberofFDSArraysListBox        matlab.ui.control.ListBox
        NumberofFDSArraysListBoxLabel   matlab.ui.control.Label
        ExtractQbfromArrayNameButton    matlab.ui.control.Button
        UITable_FDS_PSD                 matlab.ui.control.Table
        DoubleclickonArrayNametoaccesspulldownmenuLabel  matlab.ui.control.Label
        InputFDSTypeListBox             matlab.ui.control.ListBox
        InputFDSTypeListBoxLabel        matlab.ui.control.Label
        OutputPSDUnitListBox            matlab.ui.control.ListBox
        OutputPSDUnitListBoxLabel       matlab.ui.control.Label
        SaveExportFolderTab             matlab.ui.container.Tab
        TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel  matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        SetSaveExportFolderButton       matlab.ui.control.Button
        CalculateTab                    matlab.ui.container.Tab
        OptimizedPSDOuputNameEditField  matlab.ui.control.EditField
        OptimizedPSDOuputNameEditFieldLabel  matlab.ui.control.Label
        PlateauMinimumOctaveSpacingListBox  matlab.ui.control.ListBox
        PlateauMinimumOctaveSpacingListBoxLabel  matlab.ui.control.Label
        OptimizationGoalListBox         matlab.ui.control.ListBox
        OptimizationGoalListBoxLabel    matlab.ui.control.Label
        NumberofPSDCoordinatesListBox   matlab.ui.control.ListBox
        NumberofPSDCoordinatesListBoxLabel  matlab.ui.control.Label
        MaxSlopedBoctEditField          matlab.ui.control.EditField
        MaxSlopedBoctEditFieldLabel     matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        PSDDurationsecEditField         matlab.ui.control.EditField
        PSDDurationsecEditFieldLabel    matlab.ui.control.Label
        CalculateButton_env             matlab.ui.control.Button
        ResultsExportTab                matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        ExportOutputsButton             matlab.ui.control.Button
        psd_envelope_via_fdsver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        nQ    % Description
        nb    %
        num   % 
        msz   %
        iflag %
        kflag %
        THM   %
        save_name %
        fmin  %
        fmax  %f
        start_time %
        end_time   % 
        bex      %
        Q        %
        number   %
        Octave_Items  %
        ATH      %
        filename %
        name     %
        array_name %
        nfilename  %
        Oct_im   %
        oxx      %
        AData    %
        npb      %
        goal     %
        ip       %
        iu       %
        iu_th    %
        nmetric  %
        metric   %
        ntrials  %
        T_out    %
        slopec   %
        save_FDS_PSD_name  %
        power_spectral_density  %  
        FDS_name %
        big      % 
        neFDS    %
    end
    
    methods (Access = private)

        function ChangeRows_FDS_PSD(app)

            disp('ChangeRows');

            ns=app.NumberofFDSArraysListBox.Value;
            num_arrays=str2num(ns); 

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || ...
                    contains(vars(i).name,'vname_list') || ...
                    contains(vars(i).name,'amp_cycles') || ...
                    contains(vars(i).name,'range_cycles') || ...
                    contains(vars(i).name,'amp_mean_cycles') || ...
                    contains(vars(i).name,'range_cycles_max_min'))
                    cell_array{i} ='999';
                else  

                    try   
                        app.THM=evalin('base',vars(i).name);
                        sz=size(app.THM);

                        if(sz(2)==2 && contains(vars(i).name,'fds_Q'))
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            try
                ccc=unique(sort(cell_array)); 
                cnames = categorical(ccc);
                sz=size(ccc);
                app.msz=max(sz);
            catch
                app.msz=0;
            end
            
            app.kflag=0;
            nflag=0;

            if(sz(1)==0 || sz(2)==0)
                app.kflag=1;
                % warndlg('Ref 1:  No arrays available');
                return;
            end             
            
            if(app.msz<num_arrays)
                num_arrays=app.msz;
                try
                    app.NumberofFDSArraysListBox.Value=sprintf('%d',num_arrays);
                    warndlg('Number of curves reset to available number');
                catch
                end
            end 

            sq{1}=sprintf(' ');
            sb{1}=sprintf(' ');
            for i=2:num_arrays
                sq{end+1}=sprintf(' ');
                sb{end+1}=sprintf(' ');
            end
            sq=sq';
            sb=sb';

            if(num_arrays==1)
                try
                    n=1;
                    for i=1:app.msz
                        if(strcmp(app.name(1),char(cnames(i)))==1)
                            n=i;
                            nflag=1;
                            break;
                        end    
                    end    
                catch    
                end    
                cx={cnames(n)};       
            end    
            if(num_arrays==2)
                try
                    n=ones(2,1);
                    for ijk=1:2
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2))};    
            end   
            if(num_arrays==3)
                cx={cnames(1);cnames(1);cnames(1)};     
            end    
            
            if(num_arrays==4)
                try
                    n=ones(4,1);
                    for ijk=1:4
                        for i=1:app.msz
                            if(strcmp(app.name(ijk),char(cnames(i)))==1)
                                n(ijk)=i;
                                nflag=1;
                                break;
                            end    
                        end        
                    end  
                catch    
                end    
                cx={cnames(n(1));cnames(n(2));cnames(n(3));cnames(n(4))};
            end     
            if(num_arrays==5)
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};
            end       
            if(num_arrays==6)          
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};          
            end 
            if(num_arrays==7)          
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};             
            end             
            if(num_arrays==8)          
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};              
            end  
            if(num_arrays==9)          
                cx={cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1);cnames(1)};              
            end            

            try
                tdata = table(cx,sq,sb,'VariableNames',{'FDS Array Name','Q','b'});
            catch

                fprintf('\n  app.num=%d \n',app.num)
                warndlg('Change Rows: No arrays found');
            end

            try
                app.UITable_FDS_PSD.Data=tdata;  
            catch
                warndlg('Table failed')
                return;
            end

            if(nflag==1)
                extractbQ(app);
            end

        end        

        function eFDS(app)

            Items = char(app.InputFDSTypeListBox.Items);
            im=app.InputFDSTypeListBox.Value;
            [app.neFDS]=find_index(Items,im);

            if(app.neFDS==1)
                ss{1}='G^2/Hz';
            end    
            if(app.neFDS==2)
                ss{1}='(in/sec)^2/Hz';
                ss{2}='(m/sec)^2/Hz';            
            end    
            if(app.neFDS==3)
                ss{1}='in^2/Hz';
                ss{2}='m^2/Hz';            
            end    
            if(app.neFDS==4)
                ss{1}='psi^2/Hz';
                ss{2}='Pa^2/Hz';            
            end  
    
            app.OutputPSDUnitListBox.Items=ss;
    
        end    
    
       function cleartext(app)            
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.ExportOutputsButton.Visible='off';
       end    
    
       function extractbQ(app)
     
                N=str2num(app.NumberofFDSArraysListBox.Value);
                app.AData=app.UITable_FDS_PSD.Data;
    
                G=app.AData;
    
                GG=table2cell(G);
    
                for i=1:N
                    app.FDS_name{i}=char(GG{i,1});
                end
    
                sq{1}=sprintf(' ');
                sb{1}=sprintf(' ');
                for i=2:N
                    sq{end+1}=sprintf(' ');
                    sb{end+1}=sprintf(' ');
                end
                sq=sq';
                sb=sb';
    
                for i=1:N
                    for j=100:-1:1
                        ss=sprintf('Q%d',j);
                        if(contains(app.FDS_name{i},ss))
                            ss=sprintf('%d',j);
                            G{i,2}={ss};
                            break;
                        end    
                    end
                end    
    
                for i=1:N
                    for j=200:-1:1
                        jj=j/10;
                        ss=sprintf('b%g',jj);
                        if(contains(app.FDS_name{i},ss))
                            ss=sprintf('%d',jj);
                            G{i,3}={ss};
                            break;
                        end    
                    end
                end                
    
                try
                    app.UITable_FDS_PSD.Data=G;  
                catch
                    warndlg('Table failed')
                    return;
                end    
        end
        function ClosePlots(app)
            for i=1:120
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.ExportOutputsButton.Visible='off';
            eFDS(app);
            ChangeRows_FDS_PSD(app);         

            try
                if(isdeployed)
                else    
                    app.FolderEditField.Value=pwd;
                end
            catch
            end
            
        end

        % Callback function
        function LoadModelButton_env_2Pushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_env
        function CalculateButton_envPushed(app, event)
  
            ClosePlots(app);
            cleartext(app);
            
            app.MsgEditField.Value='a';

            ClosePlots(app);

            fig_num=1;        
            
            app.ntrials=str2num(app.NumberofTrialsEditField.Value);
            app.T_out=str2num(app.PSDDurationsecEditField.Value);

            ssc=str2num(app.MaxSlopedBoctEditField.Value);
            FDS_PSD.slopec=ssc;

            app.slopec=(ssc/10)/log10(2);

            FDS_PSD.ntrials=app.ntrials;
            FDS_PSD.T_out=app.T_out;
            
            Items = char(app.InputFDSTypeListBox.Items);
            im=app.InputFDSTypeListBox.Value;
            [app.nmetric]=find_index(Items,im);
            FDS_PSD.nmetric=im;

            Items = char(app.OutputPSDUnitListBox.Items);
            im=app.OutputPSDUnitListBox.Value;
            [app.iu]=find_index(Items,im);
            FDS_PSD.iu=im;

            Items = char(app.OptimizationGoalListBox.Items);
            im=app.OptimizationGoalListBox.Value;
            [app.goal]=find_index(Items,im);
            FDS_PSD.goal=im;

            Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
            im=app.PlateauMinimumOctaveSpacingListBox.Value;
            [app.ip]=find_index(Items,im);
            FDS_PSD.ip=im;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            im=app.NumberofFDSArraysListBox.Value;
            N=str2num(app.NumberofFDSArraysListBox.Value);
            FDS_PSD.N=im;

            app.Q=zeros(N,1);
            app.bex=zeros(N,1);
            
            app.AData=app.UITable_FDS_PSD.Data;
            FDS_PSD.AData=app.AData;

            A=table2cell(app.UITable_FDS_PSD.Data);

            clear app.Q;
            clear app.bex;
            
            for i=1:N
                app.FDS_name{i}=char(A{i,1});
            end

            for i=1:N
                try
                    app.Q(i)=str2num(strtrim(A{i,2}));
                catch
                end
            end

            for i=1:N
                try
                    app.bex(i)=str2num(strtrim(A{i,3}));
                catch
                end
            end
                
            for i=1:N
                data_s{i,1}=app.FDS_name{i};
                data_s{i,2}=sprintf('%g',app.Q(i));
                data_s{i,3}=sprintf('%g',app.bex(i));      
            end
            
            for i=1:N
            
                try
                    FS=app.FDS_name{i};
                    try
                        aq=evalin('base',FS);  
                    catch
                        try
                            FSS=sprintf('%s.txt',FS);
                            aq=readmatrix(FSS);
                            assignin('base',FS,aq)
                            aq=evalin('base',FS); 
                        catch
                            out1=sprintf('FDS array not found: %d %s',i,FS);
                            warndlg(out1);
                            return; 
                        end    
                    end

                    if(i==1)
                        app.fmin=aq(1,1);
                        app.fmax=aq(end,1);
                    end    

                catch
                    out1=sprintf('FDS array not found: %d %s',i,FS);
                    warndlg(out1);
                    return; 
                end
                
                if(isempty(aq))
                    warndlg('FDS array is empty');       
                    return;
                end
                
                if(i==1)
                    fn=aq(:,1);
                    n_ref=length(fn);
                    fds_ref=zeros(n_ref,N);
                end
                
                if(length(aq(:,2))~=n_ref)
                    warndlg('FDS length error');
                    return;
                end
                
                try
                   for k=1:n_ref
                        fds_ref(k,i)=aq(k,2);
                   end
                catch
                    warndlg('FDS array error');        
                    return;         
                end
                
            end               

            try
                f1=fn(1);
                f2=fn(end);
            catch
                warndlg('fn failed');
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.NumberofPSDCoordinatesListBox.Items);
            im=app.NumberofPSDCoordinatesListBox.Value;
            [app.npb]=find_index(Items,im);
            FDS_PSD.npb=im;
            
            if(app.npb==1)
                nbreak=2;
            end
            if(app.npb==2)
                nbreak=3;    
            end
            if(app.npb==3)
                nbreak=3;    
            end
            if(app.npb==4)
                nbreak=4;    
            end
            if(app.npb==5)
                nbreak=4;    
            end
            if(app.npb==6)
                nbreak=5;    
            end
            if(app.npb==7)
                nbreak=5;    
            end
            if(app.npb==8)
                nbreak=6;    
            end
            if(app.npb==9)
                nbreak=5;    
            end
            if(app.npb==10)
                nbreak=4;    
            end
            if(app.npb==11)
                nbreak=3;    
            end
            if(app.npb==12)
                nbreak=8;    
            end
            if(app.npb==13)
                nbreak=10;    
            end
            if(app.npb==14)
                nbreak=length(fn);
            end
               
            app.npb
            nbreak

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            damp=zeros(N,1);
            
            for i=1:N
                damp(i)=1/(2*app.Q(i));
                
                if(damp(i)>=1.0e-10 && damp(i)<=0.5)
                else
                    warndlg('Q value error');
                    fprintf(' i=%d damp=%8.4g  Q=%8.4g \n',i,damp(i),app.Q(i));
                    return;
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            record = 1.0e+90;
            grmslow=1.0e+50;
            vrmslow=1.0e+50;
            drmslow=1.0e+50;
            jrmslow=1.0e+50;
            %
            xf=zeros(nbreak,1);
            xapsd=zeros(nbreak,1);
            %         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
            initial=2;
            final=2;
            
            disp(' ');
            disp(' Generate sample PSDs');
            disp(' ');
            %
            progressbar;
            
            NLL=round(app.ntrials/2);
            if(NLL<10)
                NLL=10;
            end
            
            if(app.ip==1)
                plateau=1;
            end
            if(app.ip==2)
                plateau=2/3;
            end
            if(app.ip==3)
                plateau=1/2; 
            end
            if(app.ip==4)
                plateau=1/3; 
            end
            if(app.ip==5)
                plateau=1/4; 
            end

            if(app.npb==14)
                plateau=0;
            end    
            
            for ik=1:app.ntrials
                
                progressbar(ik/app.ntrials);
                
            %	   
                if(rand()>0.5 || ik<NLL)
            %	   
			            % Generate the sample psd
                        [f_sam,apsd_sam,max_sss,app.slopec]=...
                            env_generate_sample_psd_alt(n_ref,nbreak,app.npb,fn,ik,app.slopec,initial,final,f1,f2,plateau);            
            %
                else
            %
			            [f_sam,apsd_sam,max_sss,app.slopec]=...
                            env_generate_sample_psd2_alt(n_ref,nbreak,app.npb,fn,xf,xapsd,app.slopec,initial,final,ik,f1,f2,plateau);
            %
                end

            %
            %      Interpolate the sample psd
            
                if(app.npb<=13)
                    [fn,apsd_samfine]=env_interp_sam(f_sam,apsd_sam,nbreak,n_ref,fn);
                else
                    fn=f_sam;
                    apsd_samfine=apsd_sam;
                end
            % 
                a11=max(apsd_samfine);
                a22=min(apsd_samfine);
                
                if(a22<1.0e-20)
                    fprintf('\n max(apsd_samfine)=%8.4g  min(apsd_samfine)=%8.4g \n\n',a11,a22);
                    return;
                end
            
            %      Calculate the fds of the sample psd
   
                [fds_samfine]=env_fds_batch_alt_apvtp(apsd_samfine,n_ref,fn,damp,app.bex,app.T_out,app.iu,app.nmetric);
                
            %      Compare the sample fds with the reference fds
                [scale]=env_compare_alt(n_ref,fds_ref,fds_samfine,app.bex);
            %
            %      scale the psd
                scale=(scale^2.);
                apsd_sam=apsd_sam*scale;
            %       
            %      calculate the grms value 
            %             
                [grms,jrms]=env_grms_sam2(nbreak,f_sam,apsd_sam);
                [vrms]=env_vrms_sam(nbreak,f_sam,apsd_sam);
                [drms]=env_drms_sam(nbreak,f_sam,apsd_sam);
            %
                [app.iflag,record]=env_checklow3(jrms,grms,vrms,drms,jrmslow,grmslow,vrmslow,drmslow,record,app.goal); 
                [max_sss,min_fff]=freq_slope_check(f_sam,apsd_sam);
                    
                if(app.iflag==1 && ((app.npb<=13 && max_sss<app.slopec && min_fff>plateau) || app.npb==14))      
            %
                    if(drms<drmslow)
                        drmslow=drms;
                    end
		            if(vrms<vrmslow)
			            vrmslow=vrms;
                    end
		            if(grms<grmslow)
			            grmslow=grms;
                    end
		            if(jrms<jrmslow)
			            jrmslow=jrms;
                    end        
            %
                    f_sam=fix_size(f_sam);
                    apsd_sam=fix_size(apsd_sam);
            
                    xf=f_sam;
 		            xapsd=apsd_sam;
                    
                    app.power_spectral_density=[f_sam(1:nbreak) apsd_sam(1:nbreak)];
                    
                    drms_rec=drms;
                    vrms_rec=vrms;
                    grms_rec=grms;
             
                    fprintf('\n Trial %ld, PSD Coordinates \n\n',ik);
                    
                    if(app.npb<=13)
                    
                        disp('  Freq(Hz)  Accel(G^2/Hz) '); 
                        for i=1:nbreak
                            fprintf(' %8.2f \t %8.4g \n',f_sam(i),apsd_sam(i));
                        end
                    
                        disp(' ');     
                    end
            %
                    fprintf('   Trial: drms=%10.4g  vrms=%10.4g  grms=%10.4g \n',drms,vrms,grms); 
                    fprintf('  Record: drms=%10.4g  vrms=%10.4g  grms=%10.4g \n\n',drms_rec,vrms_rec,grms_rec); 

                end
            
            end

            app.MsgEditField.Value='b';
            
            xf=round(xf);
            
            pause(0.3);
            progressbar(1);
            
            %       
            % Interpolate the best psd
            %
            [xapsdfine]=env_interp_best(nbreak,n_ref,xf,xapsd,fn);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            xapsdfine=fix_size(xapsdfine);
            
            %
            % Calculate the fds of the best psd
            %
            n_ref=length(xapsdfine);
            
            [xfds]=env_fds_batch_alt_apvtp(xapsdfine,n_ref,fn,damp,app.bex,app.T_out,app.iu,app.nmetric);
   
            %
               grms=grms_rec;
               vrms=vrms_rec;
               drms=drms_rec;

            app.MsgEditField.Value='c';

            %
            disp('_____________________________________________________________________');
            %
            disp('Optimum Case');
            disp(' ');
            
            ss=sprintf('Optimum Case ');

            if(app.iu==1)
                fprintf(' Freq(Hz) \t Accel(G^2/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Accel(G^2/Hz)  \n');
            end
            if(app.iu==2)
                fprintf(' Freq(Hz) \t Accel((m/sec^2)^2)/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Accel((m/sec^2)^2)/Hz  \n');
            end
            if(app.iu==3)
                fprintf(' Freq(Hz) \t Pressure(psi^2/Hz)  \n');
                ss2=sprintf(' Freq(Hz) \t Pressure(psi^2/Hz)  \n');
            end

            ss=sprintf('%s\n\n%s',ss,ss2);
            
            for i=1:nbreak
                out1=sprintf(' %6.1f \t%8.4g  ',app.power_spectral_density(i,1),app.power_spectral_density(i,2));
                disp(out1);

                ss=sprintf('%s\n%s',ss,out1);
            end

            [max_sss,min_fff]=freq_slope_check(app.power_spectral_density(:,1),app.power_spectral_density(:,2));
                
            fprintf('\n max slope=%8.4g  min freq sep=%8.4g\n\n',max_sss,min_fff);
            
            %
            fprintf(' drms=%10.4g  vrms=%10.4g  grms=%10.4g \n',drms,vrms,grms);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            fn=fn(1:n_ref);
            xfds=xfds(1:n_ref,:);
            fds_ref=fds_ref(1:n_ref,:);
            
            for i=1:length(damp)    
            %
                xx=zeros(n_ref,1);
                ff=zeros(n_ref,1);
            %        
                for k=1:n_ref
                    xx(k)=xfds(k,i);
                    ff(k)=fds_ref(k,i);
                end
            %
                xx=fix_size(xx);
                ff=fix_size(ff);
                fn=fix_size(fn);
            %
                fds1=[fn xx];
                fds2=[fn ff];
            %
            end    

            if(app.nmetric<=3)
                ylab='Accel (G^2/Hz)';
                t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g GRMS ',grms);
                s4=sprintf(' Overall Level = %6.3g GRMS ',grms);
            else 
                if(app.iu==3)
                    ylab='Pressure (psi^2/Hz)';   
                    t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g psi rms ',grms);
                    s4=sprintf(' Overall Level = %6.3g psi rms ',grms);
                else
                    ylab='Pressure (Pa^2/Hz)';   
                    t_string=sprintf(' Power Spectral Density  Overall Level = %6.3g Pa rms ',grms);
                    s4=sprintf(' Overall Level = %6.3g Pa rms ',grms);
                end    
            end  

             app.MsgEditField.Value='0';

            try
                 app.MsgEditField.Value='1';
            
                x_label=sprintf(' Frequency (Hz)');
                
                [fig_num,h2]=plot_PSD_function(fig_num,x_label,ylab,t_string,app.power_spectral_density,app.fmin,app.fmax);
                   
                %
                app.MsgEditField.Value='2';
                
                app.MsgEditField.Value='3';

            catch
                app.MsgEditField.Value='psd plot failed'           
                % warndlg('psd plot failed')
                return;
            end

            app.MsgEditField.Value='5';

            try
                    app.MsgEditField.Value='6';

                    %
                    x_label=sprintf(' Natural Frequency (Hz)');
                    ylab='Damage Index';
                    %
                    disp(' ');
                    disp(' FDS output arrays: ');
                    disp('   ');
                    
                    uiu=(length(unique(damp))+length(unique(app.bex))); 

                    fn=fn(1:n_ref);
                    xfds=xfds(1:n_ref,:);
                    fds_ref=fds_ref(1:n_ref,:);
                    
                    for i=1:length(damp)            
                    %
                            xx=zeros(n_ref,1);
                            ff=zeros(n_ref,1);
                    %        
                            for k=1:n_ref
                                xx(k)=xfds(k,i);
                                ff(k)=fds_ref(k,i);
                            end
                    %
                            xx=fix_size(xx);
                            ff=fix_size(ff);
                            fn=fix_size(fn);
                    %
                            fds1=[fn xx];
                            fds2=[fn ff];
                    %
                    %%%%%%%%%%%
                    %
                            sbex=sprintf('%g',app.bex(i));
                            sbex=strrep(sbex, '.', 'p');
                            output_name=sprintf('psd_fds_Q%g_b%s',app.Q(i),sbex);
                            output_name2=sprintf('    %s',output_name);
                            disp(output_name2);
                            assignin('base', output_name, fds1);
                    %
                    %%%%%%%%%%%
                    %       
                            output_name_ref=sprintf('time_history_fds_Q%g_b%s',app.Q(i),sbex);
                            assignin('base', output_name_ref, fds2);
                    
                            fprintf('    %s\n',output_name_ref);        
                    %
                    %%%%%%%%%%%
                    %
                            if(uiu~=4)
                                leg_a=sprintf('PSD Envelope');
                                leg_b=sprintf('Measured Data');
                    %
                                [fig_num,h3]=...
                                plot_fds_two_h2(fig_num,x_label,ylab,fds1,fds2,leg_a,leg_b,app.Q(i),app.bex(i),app.iu,app.nmetric);    
                            
                            end
                    %                        
                    end    
   
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %
                    
                    if(uiu==4)
                       
                        [fig_num,h3]=fds_plot_2x2_alt_h2(fig_num,app.Q,app.bex,fn,ff,xx,xfds,fds_ref,app.nmetric,app.iu);
                
                        [fig_num,h4]=psd_fds_plot_2x2_alt_h2(fig_num,app.Q,app.bex,fn,xfds,...
                                                             fds_ref,app.nmetric,app.iu,t_string,...
                                                             app.power_spectral_density,app.fmin,app.fmax);    
                    end

            catch
                warndlg('uiu 4 failed');
                return;
            end

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            app.MsgEditField.Value='7';

            try
            
                disp(' ');
                disp(' The fatigue damage spectra is calculated from the amplitude (peak-valley)/2 ');
                            
                output_name=strtrim(app.OptimizedPSDOuputNameEditField.Value);
                
                if(isempty(output_name))
                    warndlg('Enter output PSD name');
                    return;
                end 
    
                FDS_PSD.output_name=output_name;

                ss=sprintf('%s\n\n Duration = %g sec \n\n PSD output name: \n %s \n',ss,app.T_out,output_name);
            
            catch
                warndlg('ref 10:  failed ');
                return;
            end

            app.MsgEditField.Value='8';

            try
                    ss=sprintf('%s\n FDS of PSD Envelope \n',ss);
        
                    for i=1:length(damp)    
                    %
                            xx=zeros(n_ref,1);
                            ff=zeros(n_ref,1);
                    %        
                            for k=1:n_ref
                                xx(k)=xfds(k,i);
                                ff(k)=fds_ref(k,i);
                            end
                    %
                            xx=fix_size(xx);
                            ff=fix_size(ff);
                            fn=fix_size(fn);
                    %
                            fds1=[fn xx];
                            fds2=[fn ff];
                    %
                    %%%%%%%%%%%
                    %
                            sbex=sprintf('%g',app.bex(i));
                            sbex=strrep(sbex, '.', 'p');
                            output_name=sprintf('psd_fds_Q%g_b%s',app.Q(i),sbex);
                            output_name2=sprintf('    %s',output_name);
                            disp(output_name2);
                            assignin('base', output_name, fds1);
                            ss=sprintf('%s\n%s',ss,output_name2);
                    end

            catch
                warndlg('fds assignin failed');
                return;
            end
            
            app.MsgEditField.Value='9';

            try
                app.ResultsTextArea.Value=ss;
                app.ResultsTextArea.Visible='on';
                app.ResultsTextAreaLabel.Visible='on';
                app.ExportOutputsButton.Visible='on';
            catch
                warndlg('Text Area failed');
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            try
                app.big=FDS_PSD;
            catch
                warndlg('app.big failed');
            end

            sx=strtrim(app.OptimizedPSDOuputNameEditField.Value);

            disp(' ');
            disp(' Optimized PSD output name: ');
            fprintf('  %s \n',sx);
            try
                assignin('base',sx,app.power_spectral_density);
                ss=sprintf('Output PSD array: %s',sx);
                msgbox(ss)
            catch
                warndlg('PSD save failed');
            end            
  
        end

        % Value changed function: NumberofFDSArraysListBox
        function NumberofFDSArraysListBoxValueChanged(app, event)
         
            Nrows=str2num(app.NumberofFDSArraysListBox.Value);

            Ncolumns=3;
             
            A=table2cell(app.UITable_FDS_PSD.Data);

            B=string(A);

            sz=size(B);
            Brows=sz(1);
             
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}='';
                end    
            end    
             
            if(~isempty(B))
                M=min([ Brows Nrows ]);
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=B{i,j};
                    end    
                end   
            end
             
            app.UITable_FDS_PSD.Data=data_s;

            ChangeRows_FDS_PSD(app);

        end

        % Button pushed function: SaveModelButton_env
        function SaveModelButton_envPushed(app, event)
             
            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

            catch
                disp('Ref A');
            end

            try
                app.AData=app.UITable_FDS_PSD.Data;
                FDS_PSD.AData=app.AData;
            catch
                disp('Ref B');           
            end

            try
                im=app.NumberofFDSArraysListBox.Value;
                N=str2num(app.NumberofFDSArraysListBox.Value);
                FDS_PSD.N=im;
            catch
                disp('Ref C');
            end

            try
        
                A=table2cell(app.AData);
                B=string(A);
            
                for i=1:FDS_PSD.N
                    app.FDS_name{i}=B{i,1};
                end
            catch
                disp('Ref D');
            end

            try
                Items = char(app.NumberofPSDCoordinatesListBox.Items);
                im=app.NumberofPSDCoordinatesListBox.Value;
                [app.npb]=find_index(Items,im);
                FDS_PSD.npb=im;         
            catch
                disp('Ref E');
            end

            try
                Items = char(app.PlateauMinimumOctaveSpacingListBox.Items);
                im=app.PlateauMinimumOctaveSpacingListBox.Value;
                [app.ip]=find_index(Items,im);
                FDS_PSD.ip=im;
            catch
                disp('Ref F');
            end
            try
                ssc=str2num(app.MaxSlopedBoctEditField.Value);
                FDS_PSD.slopec=ssc;
            catch
                disp('Ref G');
            end

            try
                app.ntrials=str2num(app.NumberofTrialsEditField.Value);
                FDS_PSD.ntrials=app.ntrials;            
            catch
                disp('Ref H');
            end

            try
                app.T_out=str2num(app.PSDDurationsecEditField.Value);
                FDS_PSD.T_out=app.T_out;            
            catch
                disp('Ref I');
            end    

            try
                Items = char(app.InputFDSTypeListBox.Items);
                im=app.InputFDSTypeListBox.Value;
                [app.nmetric]=find_index(Items,im);
                FDS_PSD.nmetric=im;            
            catch
                disp('Ref J');
            end  

            try
                Items = char(app.OutputPSDUnitListBox.Items);
                im=app.OutputPSDUnitListBox.Value;
                [app.iu]=find_index(Items,im);
                FDS_PSD.iu=im;            
            catch
                disp('Ref K');
            end  

            try
                Items = char(app.OptimizationGoalListBox.Items);
                im=app.OptimizationGoalListBox.Value;
                [app.goal]=find_index(Items,im);
                FDS_PSD.goal=im;            
            catch
                disp('Ref L');
            end

            try
                FDS_PSD.name=app.FDS_name;
            catch
                disp('Ref M');  
            end

            try
                sz=size(FDS_PSD.name);

                for i=1:sz(1)
                    FS=FDS_PSD.name{i};
                    writefname=sprintf('%s.mat',FS);

                    try
                        save_mat(writefname, writepname,FS)
                    catch
                        disp('Ref N');
                    end
                end               
            catch
                disp('Ref O');
            end

               
            try 
                ns= inputdlg({'Model Save Filename'});
            catch
                disp('Ref P');
            end    

            try
                structnames = fieldnames(FDS_PSD, '-full'); % fields in the struct
            catch
                disp('Ref Q');
            end

            try
                elk=char(fullfile(writepname, ns));
                save(elk, 'FDS_PSD');
            catch
                warndlg('Save error');
                return;
            end

            msgbox('Save complete')
        end

        % Button pushed function: LoadModelButton_env
        function LoadModelButton_envPushed(app, event)
            
            try
                writepname = app.FolderEditField.Value;

                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

            catch
            end
            
            try
                ss=sprintf('%s\\*.mat',writepname);
                ff = uigetfile(ss, 'Select load model file');
                NAME=char(fullfile(writepname,ff));
            catch
            end    
            
            try

                try
                    struct=load(NAME);
                    structnames = fieldnames(struct, '-full'); % fields in the struct        
                    k=length(structnames);
                catch
                    NAME
                    disp('NAME fail');
                end
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end

            catch
                warndlg('load failed')
                return;
            end
            
            % struct
            
            try
               FDS_PSD=evalin('base','FDS_PSD');
            catch
               warndlg(' evalin failed ');
               return;
            end
            
            %%%%%%%%%%%%

            try
                app.NumberofFDSArraysListBox.Value=sprintf('%s',FDS_PSD.N);
            catch
            end

            try
                app.OutputPSDUnitListBox.Value=sprintf('%s',FDS_PSD.iu);
            catch         
            end

            try
                app.OptimizationGoalListBox.Value=sprintf('%s',FDS_PSD.goal);
            catch  
            end

            try
                app.PlateauMinimumOctaveSpacingListBox.Value=sprintf('%s',FDS_PSD.ip);
            catch  
            end

            try
                app.NumberofPSDCoordinatesListBox.Value=sprintf('%s',FDS_PSD.npb);
            catch  
            end

            %%%%%%%%%%%%

            try 
                app.NumberofTrialsEditField.Value=sprintf('%d',FDS_PSD.ntrials);
            catch
            end 

            try 
                app.PSDDurationsecEditField.Value=sprintf('%g',FDS_PSD.T_out);
            catch 
            end 

            try 
                app.MaxSlopedBoctEditField.Value=sprintf('%g',FDS_PSD.slopec);
            catch
            end   

            try
                ChangeRows_FDS_PSD(app);
            catch   
            end

            try
                app.UITable_FDS_PSD.Data=FDS_PSD.AData;
            catch  
            end

            try
                nnn=char(FDS_PSD.name)

                sz=size(nnn)

                try
    
                    for i=1:sz(1)
                        
                        fname=nnn(i,:)
                                
                        try
                            aa=evalin('base',fname);   
                        catch
                            try
                                load_mat(writepname,fname);
                            catch    
                                disp('load failed')
                            end    
                        end
                              
                    end

                catch
                    disp('loop failed')
                end

                disp('pass')
            catch
                disp('fail')
            end    

            msgbox('Load Complete')

        end

        % Button pushed function: ExtractQbfromArrayNameButton
        function ExtractQbfromArrayNameButtonPushed(app, event)
            extractbQ(app);
        end

        % Button pushed function: ExportOutputsButton
        function ExportOutputsButtonPushed(app, event)
            try
                writepname = app.FolderEditField.Value;
                
                if(isempty(writepname))
                    msgbox('Select Save/Export Folder at top of GUI');
                    return;
                end    

                ddd=app.power_spectral_density;

                sz=size(ddd);

                for i=1:sz(1)
                    ddd(i,1)=str2num(sprintf('%5.1f',ddd(i,1)));
                    ddd(i,2)=str2num(sprintf('%7.3g',ddd(i,2)));                  
                end    


                ns= app.OptimizedPSDOuputNameEditField.Value;
                n1 = fullfile(writepname, ns);
                writematrix(ddd,n1,'Delimiter','\t');
                msgbox('Export complete');
            catch
                warndlg('Export failed');
            end    
        end

        % Callback function
        function SetSaveExportFolderButtonPushed(app, event)
            selected_dir = uigetdir();
            app.FolderEditField.Value=selected_dir; 
        end

        % Button pushed function: SetSaveExportFolderButton
        function SetSaveExportFolderButtonPushed2(app, event)
            selected_dir = uigetdir();
            try
                app.FolderEditField.Value=selected_dir;
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.psd_envelope_via_fdsver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.psd_envelope_via_fdsver10byTomIrvineLabel.Text = 'psd_envelope_via_fds      ver 1.0     by  Tom Irvine          This script derives a PSD envelope for a FDS.  ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.OutputPSDUnitListBoxLabel = uilabel(app.InputTab);
            app.OutputPSDUnitListBoxLabel.Text = 'Output PSD Unit';
            app.OutputPSDUnitListBox = uilistbox(app.InputTab);
            app.OutputPSDUnitListBox.Items = {'G^2/Hz', '(m/sec^2)^2/Hz', '(psi^2)/Hz'};
            app.OutputPSDUnitListBox.Value = 'G^2/Hz';
            app.InputFDSTypeListBoxLabel = uilabel(app.InputTab);
            app.InputFDSTypeListBoxLabel.Text = 'Input FDS Type';
            app.InputFDSTypeListBox = uilistbox(app.InputTab);
            app.InputFDSTypeListBox.Items = {'Absolute Acceleration', 'Pseudo Velocity', 'Relative Displacement', 'Transmitted Pressure'};
            app.InputFDSTypeListBox.Value = 'Absolute Acceleration';
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel = uilabel(app.InputTab);
            app.DoubleclickonArrayNametoaccesspulldownmenuLabel.Text = 'Double click on Array Name to access pull down menu.';
            app.UITable_FDS_PSD = uitable(app.InputTab);
            app.UITable_FDS_PSD.ColumnName = {'FDS Array Name'; 'Q'; 'b'};
            app.UITable_FDS_PSD.RowName = {};
            app.UITable_FDS_PSD.ColumnEditable = true;
            app.ExtractQbfromArrayNameButton = uibutton(app.InputTab, 'push');
            app.ExtractQbfromArrayNameButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractQbfromArrayNameButtonPushed, true);
            app.ExtractQbfromArrayNameButton.Text = 'Extract Q & b from Array Name';
            app.NumberofFDSArraysListBoxLabel = uilabel(app.InputTab);
            app.NumberofFDSArraysListBoxLabel.Text = {'Number of '; 'FDS Arrays'};
            app.NumberofFDSArraysListBox = uilistbox(app.InputTab);
            app.NumberofFDSArraysListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofFDSArraysListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofFDSArraysListBoxValueChanged, true);
            app.NumberofFDSArraysListBox.Value = '1';
            app.Label = uilabel(app.InputTab);
            app.Label.Text = {'Each input file must have two columns:  natural frequency (Hz) & FDS (unit^b) .   '; ''; 'Each needs to have the same natural frequency vector.      Q = amplification factor      b = fatigue exponent'; ''; ''};
            app.SaveExportFolderTab = uitab(app.TabGroup);
            app.SaveExportFolderTab.Title = 'Save/Export Folder';
            app.SetSaveExportFolderButton = uibutton(app.SaveExportFolderTab, 'push');
            app.SetSaveExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveExportFolderButtonPushed2, true);
            app.SetSaveExportFolderButton.Text = 'Set Save/Export Folder';
            app.FolderEditField = uieditfield(app.SaveExportFolderTab, 'text');
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel = uilabel(app.SaveExportFolderTab);
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel.Text = {'The Save/Export Folder must be outside of the vibrationdata_alt folder '; 'if using the standalone exe version.  Note that is a slow function.'};
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton_env = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_env.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_envPushed, true);
            app.CalculateButton_env.Text = 'Calculate';
            app.PSDDurationsecEditFieldLabel = uilabel(app.CalculateTab);
            app.PSDDurationsecEditFieldLabel.Text = 'PSD Duration (sec)';
            app.PSDDurationsecEditField = uieditfield(app.CalculateTab, 'text');
            app.PSDDurationsecEditField.Value = '60';
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofTrialsEditField.Value = '2000';
            app.MaxSlopedBoctEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxSlopedBoctEditFieldLabel.Text = 'Max Slope (dB/oct)';
            app.MaxSlopedBoctEditField = uieditfield(app.CalculateTab, 'text');
            app.MaxSlopedBoctEditField.Value = '9';
            app.NumberofPSDCoordinatesListBoxLabel = uilabel(app.CalculateTab);
            app.NumberofPSDCoordinatesListBoxLabel.Text = 'Number of PSD Coordinates';
            app.NumberofPSDCoordinatesListBox = uilistbox(app.CalculateTab);
            app.NumberofPSDCoordinatesListBox.Items = {'2 arbitrary', '3 arbitrary', '3 ramp-plateau', '4 arbitrary', '4 ramp-plateau-ramp', '5 arbitrary', '5 ramp-plateau-ramp-plateau', '6 ramp-plateau-ramp-plateau-ramp', '5 ramp-ramp-plateau-ramp', '4 ramp-ramp-plateau', '2 plateau-ramp', '8 four ramps & three plateaus ', '10 five ramps & four plateaus ', 'arbitrary with same frequencies as input FDS'};
            app.NumberofPSDCoordinatesListBox.Value = '2 arbitrary';
            app.OptimizationGoalListBoxLabel = uilabel(app.CalculateTab);
            app.OptimizationGoalListBoxLabel.Text = 'Optimization Goal';
            app.OptimizationGoalListBox = uilistbox(app.CalculateTab);
            app.OptimizationGoalListBox.Items = {'acceleration', 'acceleration & velocity', 'acceleration, velocity, displacement', 'displacement', 'velocity & displacement'};
            app.OptimizationGoalListBox.Value = 'acceleration';
            app.PlateauMinimumOctaveSpacingListBoxLabel = uilabel(app.CalculateTab);
            app.PlateauMinimumOctaveSpacingListBoxLabel.Text = {'Plateau Minimum '; 'Octave Spacing'};
            app.PlateauMinimumOctaveSpacingListBox = uilistbox(app.CalculateTab);
            app.PlateauMinimumOctaveSpacingListBox.Items = {'One', 'Two-Thirds', 'One-Half', 'One-Third', 'One-Fourth'};
            app.PlateauMinimumOctaveSpacingListBox.Value = 'One-Half';
            app.OptimizedPSDOuputNameEditFieldLabel = uilabel(app.CalculateTab);
            app.OptimizedPSDOuputNameEditFieldLabel.Text = 'Optimized PSD Ouput Name';
            app.OptimizedPSDOuputNameEditField = uieditfield(app.CalculateTab, 'text');
            app.OptimizedPSDOuputNameEditField.Value = 'optimized_psd ';
            app.ResultsExportTab = uitab(app.TabGroup);
            app.ResultsExportTab.Title = 'Results & Export';
            app.ExportOutputsButton = uibutton(app.ResultsExportTab, 'push');
            app.ExportOutputsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOutputsButtonPushed, true);
            app.ExportOutputsButton.Text = 'Export Outputs';
            app.ResultsTextAreaLabel = uilabel(app.ResultsExportTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ResultsExportTab);
            app.LoadModelButton_env_2 = uibutton(app.UIFigure, 'push');
            app.LoadModelButton_env_2.Text = 'Return';
            app.SaveModelButton_env = uibutton(app.UIFigure, 'push');
            app.SaveModelButton_env.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButton_envPushed, true);
            app.SaveModelButton_env.Text = 'Save Model';
            app.LoadModelButton_env = uibutton(app.UIFigure, 'push');
            app.LoadModelButton_env.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButton_envPushed, true);
            app.LoadModelButton_env.Text = 'Load Model';
            app.MsgEditFieldLabel = uilabel(app.UIFigure);
            app.MsgEditFieldLabel.Text = 'Msg';
            app.MsgEditField = uieditfield(app.UIFigure, 'text');
            app.MsgEditField.Editable = 'off';
            app.MsgEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psd_envelope_via_fds

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end