classdef psd_plot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSDPlotUIFigure                 matlab.ui.Figure
        Label                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        dBMarginEditField               matlab.ui.control.EditField
        dBMarginEditFieldLabel          matlab.ui.control.Label
        AdddBListBox                    matlab.ui.control.ListBox
        AdddBListBoxLabel               matlab.ui.control.Label
        MaxFreqHzEditField              matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel         matlab.ui.control.Label
        MinFreqHzEditField              matlab.ui.control.EditField
        MinFreqHzEditFieldLabel         matlab.ui.control.Label
        SelectAmpitudeUnitListBox       matlab.ui.control.ListBox
        SelectAmpitudeUnitListBoxLabel  matlab.ui.control.Label
        SelectInputTypeListBox          matlab.ui.control.ListBox
        SelectInputTypeListBoxLabel     matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        PlotFrequenciesLabel            matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        ViewResultsTab                  matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SavePSDListBox                  matlab.ui.control.ListBox
        SavePSDListBoxLabel             matlab.ui.control.Label
        psd_plot_Appver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM %
        ndB %
        nL  %
        accel_psd %
        vel_psd   %
        disp_psd  %
        method  %
    end
    
    methods (Access = private)
        
        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function dB_type(app)

            Clear(app);

            Items = char(app.AdddBListBox.Items);
            im=app.AdddBListBox.Value;
            
            [app.ndB]=find_index(Items,im);

            if(app.ndB==1)
                app.dBMarginEditField.Visible='off';
                app.dBMarginEditFieldLabel.Visible='off';
            else
                app.dBMarginEditField.Visible='on';
                app.dBMarginEditFieldLabel.Visible='on';            
            end
            
        end    
        
        function L_type(app)
               
%            app.listbox_output.Visible='off';
%            app.text_output_metric.Visible='off';

            Clear(app);

            Items = char(app.SelectInputTypeListBox.Items);
            im=app.SelectInputTypeListBox.Value;
            
            [app.nL]=find_index(Items,im);
            
            if(app.nL==1)
                string_unit{1}=sprintf('G, in/sec, in');
                string_unit{2}=sprintf('G, cm/sec, mm');
                string_unit{3}=sprintf('m/sec^2, cm/sec, mm');
            
%                app.listbox_output.Visible='on';
%                app.text_output_metric.Visible='on';
            end
            if(app.nL==2)
                string_unit{1}=sprintf('in/sec');
                string_unit{2}=sprintf('m/sec');
                string_unit{3}=sprintf('cm/sec');
            end
            if(app.nL==3)
                string_unit{1}=sprintf('in');
                string_unit{2}=sprintf('m');
                string_unit{3}=sprintf('mm');
            end
            if(app.nL==4)
                string_unit{1}=sprintf('lbf');
                string_unit{2}=sprintf('N');
            end
            if(app.nL==5 || app.nL==6)
                string_unit{1}=sprintf('psi');
                string_unit{2}=sprintf('ksi');
                string_unit{3}=sprintf('Pa');
                string_unit{4}=sprintf('KPa');
                string_unit{5}=sprintf('MPa');
            end
            if(app.nL==7)
                string_unit{1}=sprintf('unit');
            end
            
            app.SelectAmpitudeUnitListBox.Items=string_unit;

        end
        
        function Clear(app)
            app.ResultsTextArea.Value=' ';
        end
        
        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.method=1;
            L_type(app);
            dB_type(app);
            update(app);
        end

        % Callback function
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Callback function
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            ClosePlots(app);

            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            num=length(app.THM(:,1));
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end
            
            
            sminf=app.MinFreqHzEditField.Value;
            smaxf=app.MaxFreqHzEditField.Value;
            
            if isempty(sminf)
                string=sprintf('%8.4g',app.THM(1,1));
                app.MinFreqHzEditField.Value=string;
            end
            
            if isempty(smaxf)
                string=sprintf('%8.4g',app.THM(end,1));
                app.MaxFreqHzEditField.Value=string;
            end

            fmin=str2num(app.MinFreqHzEditField.Value);
            fmax=str2num(app.MaxFreqHzEditField.Value);
            
            f=app.THM(:,1);
            ddd=diff(f);
            maxd=max(ddd);
            mind=min(ddd);
            ratio=(maxd-mind)/maxd;
            
            if(ratio<0.02)
            
                fmin=str2num(app.MinFreqHzEditField.Value);
                fmax=str2num(app.MaxFreqHzEditField.Value);
            
                [~,i]=min(abs(f-fmin));
                [~,j]=min(abs(f-fmax));
            
                if(i>=2 && f(i)>fmin)
                    i=i-1;
                end
            
                app.THM=app.THM(i:j,:);
            end
              
            if(app.ndB==2)
               dB=str2num(app.dBMarginEditField.Value);
               scale=10^(dB/10);
            %
               for i=1:num
                   app.THM(i,2)=app.THM(i,2)*scale;
               end
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.SelectAmpitudeUnitListBox.Items);
            im=app.SelectAmpitudeUnitListBox.Value;
            [m]=find_index(Items,im);
            
            [aslope,rms] = calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2));
            

            if(app.nL==1)
                if(m==1)
                    YS=sprintf('G');
                    YSV=sprintf('in/sec');
                    YSD=sprintf('in');
                end
                if(m==2)
                    YS=sprintf('G');
                    YSV=sprintf('cm/sec');
                    YSD=sprintf('mm');
                end
                if(m==3)
                    YS=sprintf('m/sec^2');
                    YSV=sprintf('cm/sec');
                    YSD=sprintf('mm');
                end
            end
            if(app.nL==2)
                if(m==1)
                    YS=sprintf('in/sec');
                end
                if(m==2)
                    YS=sprintf('m/sec');
                end
                if(m==3)
                    YS=sprintf('cm/sec');
                end
            end
            if(app.nL==3)
                if(m==1)
                    YS=sprintf('in');
                end
                if(m==2)
                    YS=sprintf('m');
                end
                if(m==3)
                    YS=sprintf('mm');
                end
            end
            if(app.nL==4)
                if(m==1)
                    YS=sprintf('lbf');
                end
                if(m==2)
                    YS=sprintf('N');
                end
            end
            if(app.nL==5 || app.nL==6)
                if(m==1)
                    YS=sprintf('psi');
                end
                if(m==2)
                    YS=sprintf('ksi');
                end
                if(m==3)
                    YS=sprintf('Pa');
                end
                if(m==4)
                    YS=sprintf('KPa');
                end
                if(m==5)
                    YS=sprintf('MPa');
                end
            end
            if(app.nL==7)
                YS=sprintf('unit');
            end
            
            k = strfind(YS,'/');
            
            if(app.nL==1)
                out2=sprintf('Accel (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Accel ((%s)^2/Hz)',YS);
                end
            end
            if(app.nL==2)
                out2=sprintf('Vel ((%s)^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Vel ((%s)^2/Hz)',YS);
                end
            end
            if(app.nL==3)
                out2=sprintf('Disp (%s^2/Hz)',YS);
            end
            if(app.nL==4)
                out2=sprintf('Force (%s^2/Hz)',YS);
            end
            if(app.nL==5)
                out2=sprintf('Pressure (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Pressure ((%s)^2/Hz)',YS);
                end
            end
            if(app.nL==6)
                out2=sprintf('Stress (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Stress ((%s)^2/Hz)',YS);
                end
            end
            if(app.nL==7)
                out2=sprintf('(%s^2/Hz)',YS);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            qrms=rms;
            
            if(length(app.THM(:,1))<=60)
            
                s1=sprintf('   Freq       Amplitude                  ');
                if(app.nL==1)
                    s1=sprintf('   Freq       Accel                  ');
                end
                if(app.nL==2)
                    s1=sprintf('   Freq        Vel                  ');
                end
                if(app.nL==3)
                    s1=sprintf('   Freq       Disp                  ');
                end                
                if(app.nL==4)
                    s1=sprintf('   Freq       Force                  ');
                end   
                if(app.nL==5)
                    s1=sprintf('   Freq      Pressure                  ');
                end   
                if(app.nL==6)
                    s1=sprintf('   Freq       Stress                  ');
                end                   

                s2=sprintf('   (Hz)     (%s^2/Hz)    Slope     dB/oct',YS);
            
                s3=sprintf(' %8.4g \t %8.4g \n',app.THM(1,1),app.THM(1,2));
            
                sss=sprintf('%s\n%s\n%s',s1,s2,s3); 

                for i=2:num
                    try
                        dbo=10*aslope(i-1)*log10(2);
                        sq=sprintf(' %8.4g \t %8.4g \t %7.3g \t %7.3g \n',app.THM(i,1),app.THM(i,2),aslope(i-1),dbo);
                        sss=sprintf('%s%s',sss,sq);
                    catch
                        break;
                    end
                end
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            
            clear length;
            f=app.THM(:,1);
            a=app.THM(:,2);
            nA=length(a);
            
            dpsd=zeros(nA,2);
            vpsd=zeros(nA,2);

            disp('***')
            app.nL

            if(app.nL==1) % integerate to velocity & displacement
            %
            %    set(handles.listbox_output,'Visible','on');
            %    set(handles.text_output_metric,'Visible','on');
            %
              omega=2*pi*f;
            %
              v=zeros(nA,1);
              d=zeros(nA,1);
            %
              for i=1:nA
                v(i)=a(i)/omega(i)^2;
                d(i)=v(i)/omega(i)^2;
              end
            %
              if(f(1)<=1.0e-10)
                 f(1)=[];
                 v(1)=[];
                 d(1)=[];
              end
            %
              if(m==1)
                  v=v*386^2;
                  d=d*386^2;
              end
              if(m==2)
                  v=v*(9.81*100)^2;
                  d=d*(9.81*1000)^2;
              end
              if(m==3)
                  v=v*(100)^2;
                  d=d*(1000)^2;
              end
            %
              d=fix_size(d);
              v=fix_size(v);
            %
              dpsd=[f d];
              vpsd=[f v];

              app.accel_psd=[f a];
              app.vel_psd=[f v];
              app.disp_psd=[f d];    
            %
            
              [~,vrms]=calculate_PSD_slopes_no(f,v);
              [~,drms]=calculate_PSD_slopes_no(f,d);
            %

               sss=sprintf('\n Overall Levels \n\n');

            %
               if(m<=2)
                  out12=sprintf(' Acceleration \t = %6.3g GRMS ',rms);
                  out13=sprintf('              \t\t = %6.3g G (3-sigma) \n',3*rms);
               else
                  out12=sprintf(' Acceleration \t = %6.3g m/sec^2 RMS ',rms);
                  out13=sprintf('              \t\t = %6.3g m/sec^2 (3-sigma) \n',3*rms);
               end
            %
               if(m==1)
                  out22=sprintf('     Velocity \t = %6.3g in/sec RMS ',vrms);
                  out23=sprintf('              \t\t = %6.3g in/sec (3-sigma) \n',3*vrms);
                  out32=sprintf(' Displacement = %6.3g inch RMS ',drms);
                  out33=sprintf('              \t\t = %6.3g inch (3-sigma) ',3*drms);
               else
                  out22=sprintf('     Velocity \t = %6.3g cm/sec RMS ',vrms);
                  out23=sprintf('              \t\t = %6.3g cm/sec (3-sigma) \n',3*vrms);
                  out32=sprintf(' Displacement = %6.3g mm RMS ',drms);
                  out33=sprintf('              \t\t = %6.3g mm (3-sigma) ',3*drms);
               end
            %

               sss=sprintf('%s %s \n',sss,out12);
               sss=sprintf('%s %s \n',sss,out13);
               sss=sprintf('%s %s \n',sss,out22);
               sss=sprintf('%s %s \n',sss,out23);
               sss=sprintf('%s %s \n',sss,out32);
               sss=sprintf('%s %s \n',sss,out33);                  

            %
            else
                out12=sprintf('\n Power Spectral Density \n \n %6.3g %s RMS Overall \n',qrms,YS);
                 
                sss=out12;
            end
            
            fig_num=1;
            xlab='Frequency (Hz)';
            
            power_spectral_density=app.THM;
            
            setappdata(0,'PSD',power_spectral_density);
            
            [crms]=cumulative_rms(power_spectral_density);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.nL==1 && m<=2)
                ylab=sprintf('%sRMS',YS);
            else
                ylab=sprintf('%s RMS',YS);
            end
            
            t_string=...
                sprintf(' Cumulative RMS');
            [fig_num]=plot_cumulative_RMS(fig_num,xlab,ylab,t_string,crms,fmin,fmax);
            
            
            if(app.nL==1)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=sprintf('Disp (%s^2/Hz)',YSD);
            
                t_string=sprintf('Displacement Power Spectral Density %6.3g %s RMS Overall ',drms,YSD);
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,dpsd,fmin,fmax);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=sprintf('Vel ((%s)^2/Hz)',YSV);
            
                t_string=sprintf('Velocity Power Spectral Density %6.3g %s RMS Overall ',vrms,YSV);
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,vpsd,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=out2;
                if(m<=2)
                    t_string=sprintf('Acceleration Power Spectral Density %6.3g %sRMS Overall ',qrms,YS);
                else
                    t_string=sprintf('Acceleration Power Spectral Density %6.3g %s RMS Overall ',qrms,YS);
                end
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,fmin,fmax);
            
            else
            
                ylab=out2;
                t_string=sprintf('Power Spectral Density %6.3g %s RMS Overall ',qrms,YS);
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,fmin,fmax);
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            f=app.THM(:,1);
            a=app.THM(:,2);
            %
            
            if(f(1)<0.001)
                f(1)=0.001;
            end
            
            df=0.2;
            
            [s,~]=calculate_PSD_slopes_no(f,a);
            
            [fi,ai]=interpolate_PSD(f,a,s,df);
            
            [~,ii]=min(abs(fi-fmin));
            [~,jj]=min(abs(fi-fmax));
            
            fi=fi(ii:jj);
            ai=ai(ii:jj);
            
            [EP,vo,m0,m1,m2,m4,alpha2,e]=spectal_moments_alt(fi,ai,df);
            
            s1=sprintf(' Spectral Moments \n');
            
            s2=sprintf(' m0=%8.4g \t m1=%8.4g \t m2=%8.4g  \t m4=%8.4g \n',m0,m1,m2,m4);
            
            s3=sprintf(' alpha2=%8.4g \t e=%8.4g spectral width \n',alpha2,e);
            
            s4=sprintf(' Rate of Up-zero crossings = %7.4g Hz \n',vo);
            s5=sprintf('             Rate of peaks = %7.4g Hz ',EP);

            sss=sprintf('%s \n %s \n %s \n %s \n %s \n %s',sss,s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=sss;

        end

        % Callback function
        function SelectInputTypeListBoxValueChanged(app, event)
            L_type(app);
        end

        % Callback function
        function AdddBListBoxValueChanged(app, event)
            dB_type(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged2(app, event)
            array_method(app);
        end

        % Value changed function: SelectAmpitudeUnitListBox
        function SelectAmpitudeUnitListBoxValueChanged(app, event)
            Clear(app);
        end

        % Value changing function: dBMarginEditField
        function dBMarginEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = char(app.SavePSDListBox.Items);
            im=app.SavePSDListBox.Value;
            
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel_psd;
            end
            if(n==2)
                data=app.vel_psd;
            end
            if(n==3)
                data=app.disp_psd;
            end    

            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name, data); 
            msgbox('Data saved');       

        end

        % Value changed function: SelectInputTypeListBox
        function SelectInputTypeListBoxValueChanged2(app, event)
            L_type(app);
        end

        % Value changed function: AdddBListBox
        function AdddBListBoxValueChanged2(app, event)
            dB_type(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed2(app, event)
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSDPlotUIFigure = uifigure('Visible', 'off');
            app.PSDPlotUIFigure.Name = 'PSD Plot';
            app.psd_plot_Appver12byTomIrvineLabel = uilabel(app.PSDPlotUIFigure);
            app.psd_plot_Appver12byTomIrvineLabel.Text = 'psd_plot_App  ver 1.2   by Tom Irvine       ';
            app.TabGroup = uitabgroup(app.PSDPlotUIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.listbox_method = uilistbox(app.CalculateTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged2, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.CalculateTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed2, true);
            app.UpdateListButton.Text = 'Update List';
            app.PlotFrequenciesLabel = uilabel(app.CalculateTab);
            app.PlotFrequenciesLabel.Text = 'Plot Frequencies';
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculateTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculateTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SelectInputTypeListBoxLabel = uilabel(app.CalculateTab);
            app.SelectInputTypeListBoxLabel.Text = 'Select Input Type';
            app.SelectInputTypeListBox = uilistbox(app.CalculateTab);
            app.SelectInputTypeListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'Force', 'Pressure', 'Stress', 'Other'};
            app.SelectInputTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputTypeListBoxValueChanged2, true);
            app.SelectInputTypeListBox.Value = 'Acceleration';
            app.SelectAmpitudeUnitListBoxLabel = uilabel(app.CalculateTab);
            app.SelectAmpitudeUnitListBoxLabel.Text = 'Select Ampitude Unit';
            app.SelectAmpitudeUnitListBox = uilistbox(app.CalculateTab);
            app.SelectAmpitudeUnitListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAmpitudeUnitListBoxValueChanged, true);
            app.MinFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.MaxFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.AdddBListBoxLabel = uilabel(app.CalculateTab);
            app.AdddBListBoxLabel.Text = 'Add dB';
            app.AdddBListBox = uilistbox(app.CalculateTab);
            app.AdddBListBox.Items = {'No', 'Yes'};
            app.AdddBListBox.ValueChangedFcn = createCallbackFcn(app, @AdddBListBoxValueChanged2, true);
            app.AdddBListBox.Value = 'No';
            app.dBMarginEditFieldLabel = uilabel(app.CalculateTab);
            app.dBMarginEditFieldLabel.Text = 'dB Margin';
            app.dBMarginEditField = uieditfield(app.CalculateTab, 'text');
            app.dBMarginEditField.ValueChangingFcn = createCallbackFcn(app, @dBMarginEditFieldValueChanging, true);
            app.PlotButton = uibutton(app.CalculateTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ViewResultsTab = uitab(app.TabGroup);
            app.ViewResultsTab.Title = 'View Results';
            app.ResultsTextAreaLabel = uilabel(app.ViewResultsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ViewResultsTab);
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SavePSDListBoxLabel = uilabel(app.SaveTab);
            app.SavePSDListBoxLabel.Text = 'Save PSD';
            app.SavePSDListBox = uilistbox(app.SaveTab);
            app.SavePSDListBox.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.SavePSDListBox.Value = 'Acceleration';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveTab, 'text');
            app.ReturnButton = uibutton(app.PSDPlotUIFigure, 'push');
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.PSDPlotUIFigure);
            app.Label.Text = 'This script calculates the overall level of a power spectral density.  The input array must have two columns: freq(Hz) & amp(unit^2/Hz)';
            % Show the figure after all components are created
            app.PSDPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psd_plot_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PSDPlotUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PSDPlotUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSDPlotUIFigure)
        end
    end
end