classdef psd_tools_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        psdtoolsUIFigure                matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputPSDTab                     matlab.ui.container.Tab
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        TimeScalingTab                  matlab.ui.container.Tab
        OutputPSDReadOnlyTextArea       matlab.ui.control.TextArea
        OutputPSDReadOnlyTextAreaLabel  matlab.ui.control.Label
        OutputPSDNameEditField          matlab.ui.control.EditField
        OutputPSDNameEditFieldLabel     matlab.ui.control.Label
        ListBox_out                     matlab.ui.control.ListBox
        ListBox_in                      matlab.ui.control.ListBox
        OutputDurationEditField         matlab.ui.control.EditField
        OutputDurationEditFieldLabel    matlab.ui.control.Label
        InputDurationEditField          matlab.ui.control.EditField
        InputDurationEditFieldLabel     matlab.ui.control.Label
        FatigueExponentEditField        matlab.ui.control.EditField
        FatigueExponentEditFieldLabel   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BandSplittingTab                matlab.ui.container.Tab
        OutputPSDsReadOnlyTextArea      matlab.ui.control.TextArea
        OutputPSDsReadOnlyTextAreaLabel  matlab.ui.control.Label
        OutputArrayNamePrefixEditField  matlab.ui.control.EditField
        OutputArrayNamePrefixEditFieldLabel  matlab.ui.control.Label
        StrategyListBox                 matlab.ui.control.ListBox
        StrategyListBoxLabel            matlab.ui.control.Label
        NumberofBandsListBox            matlab.ui.control.ListBox
        NumberofBandsListBoxLabel       matlab.ui.control.Label
        CalculateButton_split           matlab.ui.control.Button
        psd_tools_aver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        method %
    end
    
    methods (Access = private)


       function update_psd(app)
            CloseFigures(app);
            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density_alt();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end      
        
        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CloseFigures(app);
            update_psd(app);
            app.OutputPSDReadOnlyTextArea.Value='';
            app.OutputPSDsReadOnlyTextArea.Value='';
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
         
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            CloseFigures(app);
            
            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            Items = char(app.ListBox_in.Items);
            im=app.ListBox_in.Value;
            [n1]=find_index(Items,im);
 

            Items = char(app.ListBox_out.Items);
            im=app.ListBox_out.Value;
            [n2]=find_index(Items,im);

            
            t1=str2num(app.InputDurationEditField.Value);
            t2=str2num(app.OutputDurationEditField.Value);
            
            t1o=t1;
            t2o=t2;
            
            b=str2num(app.FatigueExponentEditField.Value);
            
            %%%
            
            s1='sec';
            
            if(n1==1)
                t1=t1*3600;
                s1='hr';
            end
            if(n1==2)
                t1=t1*60;
                s1='min';
            end
            
            %%%
            
            s2='sec';
            
            if(n2==1)
                t2=t2*3600;
                s2='hr';
            end
            if(n2==2)
                t2=t2*60;
                s2='min';
            end
            
            %%%

            A=app.THM;
            
            [~,input_rms] = calculate_PSD_slopes(A(:,1),A(:,2));
            
            
            new_rms=(input_rms^b*(t1/t2))^(1/b);
            
            scale=(new_rms/input_rms)^2;
            
            
            B=A;
            
            sz=size(A);
            
            for i=1:sz(1)
                B(i,2)=A(i,2)*scale;
            end
            
            [~,output_rms] = calculate_PSD_slopes(B(:,1),B(:,2));
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=min(A(:,1));
            fmax=max(A(:,1));
            
            xlab='Frequency (Hz)';
            ylab='Accel (G^2/Hz)';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            t_string=sprintf('Reference PSD  %6.3g GRMS  %g %s',input_rms,t1o,s1);    
            
            [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string,A,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            t_string=sprintf('New PSD  %6.3g GRMS  %g %s',output_rms,t2o,s2);    
            
            [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string,B,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Overall Levels ');
            disp(' ');
            
            out1=sprintf(' Reference PSD = %7.3g GRMS',input_rms);
            out2=sprintf('       New PSD = %7.3g GRMS',output_rms);
            
            disp(out1);
            disp(out2);

            ss1=sprintf('  Overall Levels \n\n %s \n %s \n\n',out1,out2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp('  New PSD ');
            disp(' ');
            disp('  Freq(Hz)  Accel(G^2/Hz) ');
            disp(' ');

            ss2=sprintf(' %s New PSD  \n\n Freq(Hz)  Accel(G^2/Hz) \n\n',ss1);
            
            for i=1:sz(1)
                out1=sprintf(' %8.4g  %8.4g ',B(i,1),B(i,2));
                disp(out1);
                ss2=sprintf('%s %s \n',ss2,out1);
            end
            
            
            delta=20*log10(output_rms/input_rms);
            
            disp(' ');
            out1=sprintf(' Difference =  %6.3g dB ',delta);
            disp(out1);

            ss2=sprintf('%s \n %s ',ss2,out1);

            app.OutputPSDReadOnlyTextArea.Value=ss2;

            output_name=app.OutputPSDNameEditField.Value;

            assignin('base', output_name, B);

        end

        % Value changing function: FatigueExponentEditField
        function FatigueExponentEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.OutputPSDReadOnlyTextArea.Value='';
        end

        % Value changing function: InputDurationEditField
        function InputDurationEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.OutputPSDReadOnlyTextArea.Value='';
        end

        % Value changing function: OutputDurationEditField
        function OutputDurationEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.OutputPSDReadOnlyTextArea.Value='';
        end

        % Value changed function: ListBox_in
        function ListBox_inValueChanged(app, event)
            value = app.ListBox_in.Value;
            app.OutputPSDReadOnlyTextArea.Value='';
        end

        % Value changed function: ListBox_out
        function ListBox_outValueChanged(app, event)
            value = app.ListBox_out.Value;
            app.OutputPSDReadOnlyTextArea.Value='';
        end

        % Button pushed function: CalculateButton_split
        function CalculateButton_splitPushed(app, event)
            CloseFigures(app);
            app.OutputPSDsReadOnlyTextArea.Value='';
            
            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            A=app.THM;

            fig_num=1;
            
            fmin=min(A(:,1));
            fmax=max(A(:,1));
            
            xlab='Frequency (Hz)';
            ylab='Accel (G^2/Hz)';
            
            [~,input_rms] = calculate_PSD_slopes_no(A(:,1),A(:,2));
            
            t_string=sprintf('Reference PSD  %6.3g GRMS',input_rms);    
            [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,A,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            nb=str2num(app.NumberofBandsListBox.Value);
            
            f=A(:,1);
            a=A(:,2);
            
            tpi=2*pi;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  intlog method for numerical stability
            %
            df=0.5;
            %
            xi=min(f):df:max(f); 
            %
            xL=log10(f);
            yL=log10(a);
            xiL=log10(xi);
            %
            yiL = interp1(xL,yL,xiL);
            %
            ni=length(xi);
            %
            fi=zeros(ni,1);
            ai=zeros(ni,1);
            %
            for i=1:ni
                fi(i)=10^xiL(i);
                ai(i)=10^yiL(i);
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            n=length(f);
            %

            Items = strtrim(app.StrategyListBox.Items);
            im = strtrim(app.StrategyListBox.Value);
            nstrategy = find(strcmp(Items, im));
            
            if(nstrategy==1)
                [bpsd,numb]=bandsplit_cases(f,a,fi,ai,n,ni,df,nb);
            end
            if(nstrategy==2)
                [bpsd,numb]=bandsplit_cases_velox(f,a,fi,ai,n,ni,df,nb);  
            end
            if(nstrategy==3)
                [bpsd,numb]=bandsplit_cases_frequency(f,a,fi,ai,n,ni,df,nb);  
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            for j=1:nb
            %
                clear ff;
                clear aa;
            %
                mm=numb(j);
                ff=bpsd(j,1:mm,1);
                aa=bpsd(j,1:mm,2);
            %
                ff=fix_size(ff);
                aa=fix_size(aa);
            %
                [~,grms]=calculate_PSD_slopes_no(ff,aa);
                
                nn=length(ff);
                
                vv=zeros(nn,1);
                
                for ij=1:nn
                    vv(ij)=386^2*aa(ij)/(tpi*ff(ij))^2;
                end
                
                [~,vrms]=calculate_PSD_slopes_no(ff,vv);    
                
                disp(' ');
                out1=sprintf(' PSD %d  %7.3g GRMS, %7.3g (in/sec) RMS',j,grms,vrms);
                disp(out1);
                disp(' ');

                if(j==1)
                    out2=sprintf(' Freq(Hz)  Accel(G^2/Hz) ');         
                else    
                    out2=sprintf(' Freq(Hz)  Accel(G^2/Hz) \n');
                end  
                disp(out2);   


                if(j==1)
                    ss=sprintf('%s \n %s \n',out1,out2);        
                else    
                    ss=sprintf('%s \n %s \n %s',ss,out1,out2);
                end  
 
            %
                for i=1:mm
                    out1=sprintf(' %8.4g  %8.4g ',ff(i),aa(i));
                    disp(out1);
                    ss=sprintf('%s %s \n',ss,out1);
                end

            %
                varname1=sprintf('band%d',j);
                eval([varname1 ' =[ff aa];']);
            %
            
                AB=[ff aa]; 
            
                t_string=sprintf('Band %d PSD  %6.3g GRMS',j,grms);    
                [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string,AB,fmin,fmax);
            %
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            name=app.OutputArrayNamePrefixEditField.Value;

            output_name1=sprintf('%s_1',name);
            output_name2=sprintf('%s_2',name); 
            output_name3=sprintf('%s_3',name);
            output_name4=sprintf('%s_4',name); 

            assignin('base', output_name1, band1);
            assignin('base', output_name2, band2);

            ss=sprintf('%s \n Output arrays \n %s \n %s \n',ss, output_name1,output_name2);
            
            if(nb>=3)
                assignin('base', output_name3, band3);
                ss=sprintf('%s %s \n',ss, output_name3);
            end
            if(nb==4)
                assignin('base', output_name4, band4);
                ss=sprintf('%s %s \n',ss, output_name4);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
            if(nb==2)
                z=[band1; band2];
                zz=[ band1(1,:); band1(end,:); band2(end,:)]; 
            end            
            if(nb==3)
                z=[band1; band2; band3];
                zz=[ band1(1,:); band1(end,:); band2(end,:); band3(end,:)]; 
            end            
            if(nb==4)
                z=[band1; band2; band3; band4];
                zz=[ band1(1,:); band1(end,:); band2(end,:); band3(end,:); band4(end,:)]; 
            end

            t_stringz=sprintf('PSD with Bandsplit Markers  %6.3g GRMS',input_rms);    
            [fig_num]=plot_PSD_function_markers(fig_num,xlab,ylab,t_stringz,z,zz,fmin,fmax);   

            app.OutputPSDsReadOnlyTextArea.Value=ss;


        end

        % Value changed function: NumberofBandsListBox
        function NumberofBandsListBoxValueChanged(app, event)
            value = app.NumberofBandsListBox.Value;
            app.OutputPSDsReadOnlyTextArea.Value='';
            CloseFigures(app);
        end

        % Value changed function: StrategyListBox
        function StrategyListBoxValueChanged(app, event)
            value = app.StrategyListBox.Value;
            app.OutputPSDsReadOnlyTextArea.Value='';
            CloseFigures(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.psdtoolsUIFigure = uifigure('Visible', 'off');
            app.psdtoolsUIFigure.Name = 'psd tools';
            app.psd_tools_aver11byTomIrvineLabel = uilabel(app.psdtoolsUIFigure);
            app.psd_tools_aver11byTomIrvineLabel.Text = 'psd_tools_a   ver 1.1  by Tom Irvine';
            app.TabGroup = uitabgroup(app.psdtoolsUIFigure);
            app.InputPSDTab = uitab(app.TabGroup);
            app.InputPSDTab.Title = 'Input PSD';
            app.listbox_method_psd = uilistbox(app.InputPSDTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.InputPSDTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.InputPSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.UpdateListButton_psd = uibutton(app.InputPSDTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.InputPSDTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.InputPSDTab);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.TimeScalingTab = uitab(app.TabGroup);
            app.TimeScalingTab.Title = 'Time Scaling';
            app.Label = uilabel(app.TimeScalingTab);
            app.Label.Text = 'This script scales a PSD specification to a new level based on time scaling via equivalent fatigue damage from the GRMS levels';
            app.CalculateButton = uibutton(app.TimeScalingTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FatigueExponentEditFieldLabel = uilabel(app.TimeScalingTab);
            app.FatigueExponentEditFieldLabel.Text = 'Fatigue Exponent';
            app.FatigueExponentEditField = uieditfield(app.TimeScalingTab, 'text');
            app.FatigueExponentEditField.ValueChangingFcn = createCallbackFcn(app, @FatigueExponentEditFieldValueChanging, true);
            app.FatigueExponentEditField.Value = '6.4';
            app.InputDurationEditFieldLabel = uilabel(app.TimeScalingTab);
            app.InputDurationEditFieldLabel.Text = 'Input Duration';
            app.InputDurationEditField = uieditfield(app.TimeScalingTab, 'text');
            app.InputDurationEditField.ValueChangingFcn = createCallbackFcn(app, @InputDurationEditFieldValueChanging, true);
            app.OutputDurationEditFieldLabel = uilabel(app.TimeScalingTab);
            app.OutputDurationEditFieldLabel.Text = 'Output Duration';
            app.OutputDurationEditField = uieditfield(app.TimeScalingTab, 'text');
            app.OutputDurationEditField.ValueChangingFcn = createCallbackFcn(app, @OutputDurationEditFieldValueChanging, true);
            app.ListBox_in = uilistbox(app.TimeScalingTab);
            app.ListBox_in.Items = {'hr', 'min', 'sec'};
            app.ListBox_in.ValueChangedFcn = createCallbackFcn(app, @ListBox_inValueChanged, true);
            app.ListBox_in.Value = 'hr';
            app.ListBox_out = uilistbox(app.TimeScalingTab);
            app.ListBox_out.Items = {'hr', 'min', 'sec'};
            app.ListBox_out.ValueChangedFcn = createCallbackFcn(app, @ListBox_outValueChanged, true);
            app.ListBox_out.Value = 'hr';
            app.OutputPSDNameEditFieldLabel = uilabel(app.TimeScalingTab);
            app.OutputPSDNameEditFieldLabel.Text = 'Output PSD Name';
            app.OutputPSDNameEditField = uieditfield(app.TimeScalingTab, 'text');
            app.OutputPSDNameEditField.Value = 'scaled_psd';
            app.OutputPSDReadOnlyTextAreaLabel = uilabel(app.TimeScalingTab);
            app.OutputPSDReadOnlyTextAreaLabel.Text = 'Output PSD, Read Only';
            app.OutputPSDReadOnlyTextArea = uitextarea(app.TimeScalingTab);
            app.OutputPSDReadOnlyTextArea.Editable = 'off';
            app.BandSplittingTab = uitab(app.TabGroup);
            app.BandSplittingTab.Title = 'Band Splitting';
            app.CalculateButton_split = uibutton(app.BandSplittingTab, 'push');
            app.CalculateButton_split.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_splitPushed, true);
            app.CalculateButton_split.Text = 'Calculate';
            app.NumberofBandsListBoxLabel = uilabel(app.BandSplittingTab);
            app.NumberofBandsListBoxLabel.Text = 'Number of Bands';
            app.NumberofBandsListBox = uilistbox(app.BandSplittingTab);
            app.NumberofBandsListBox.Items = {'2', '3', '4'};
            app.NumberofBandsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofBandsListBoxValueChanged, true);
            app.NumberofBandsListBox.Value = '2';
            app.StrategyListBoxLabel = uilabel(app.BandSplittingTab);
            app.StrategyListBoxLabel.Text = 'Strategy';
            app.StrategyListBox = uilistbox(app.BandSplittingTab);
            app.StrategyListBox.Items = {'Equal Acceleration', 'Equal Velocity', 'Equal Frequency Band'};
            app.StrategyListBox.ValueChangedFcn = createCallbackFcn(app, @StrategyListBoxValueChanged, true);
            app.StrategyListBox.Value = 'Equal Acceleration';
            app.OutputArrayNamePrefixEditFieldLabel = uilabel(app.BandSplittingTab);
            app.OutputArrayNamePrefixEditFieldLabel.Text = 'Output Array Name Prefix';
            app.OutputArrayNamePrefixEditField = uieditfield(app.BandSplittingTab, 'text');
            app.OutputArrayNamePrefixEditField.Value = 'split_psd';
            app.OutputPSDsReadOnlyTextAreaLabel = uilabel(app.BandSplittingTab);
            app.OutputPSDsReadOnlyTextAreaLabel.Text = 'Output PSDs, Read Only';
            app.OutputPSDsReadOnlyTextArea = uitextarea(app.BandSplittingTab);
            app.OutputPSDsReadOnlyTextArea.Editable = 'off';
            app.ReturnButton = uibutton(app.psdtoolsUIFigure, 'push');
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.psdtoolsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = psd_tools_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.psdtoolsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.psdtoolsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.psdtoolsUIFigure)
        end
    end
end