classdef pump_noise_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OASPLdBAEditField               matlab.ui.control.EditField
        OASPLdBAEditFieldLabel          matlab.ui.control.Label
        SpecifyOverallSPLdBAListBox     matlab.ui.control.ListBox
        SpecifyOverallSPLdBAListBoxLabel  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        SpeedofSoundftsecEditField      matlab.ui.control.EditField
        SpeedofSoundftsecEditFieldLabel  matlab.ui.control.Label
        GasDensitylbmft3EditField       matlab.ui.control.EditField
        GasDensitylbmft3EditFieldLabel  matlab.ui.control.Label
        GasListBox                      matlab.ui.control.ListBox
        GasListBoxLabel                 matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SpeedRPMEditField               matlab.ui.control.EditField
        SpeedRPMEditFieldLabel          matlab.ui.control.Label
        UnitListBox                     matlab.ui.control.ListBox
        UnitListBoxLabel                matlab.ui.control.Label
        PumpPowerEditField              matlab.ui.control.EditField
        PumpPowerEditFieldLabel         matlab.ui.control.Label
        pump_noise_Appver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        iunit  % Description
        ioadBA %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                  
        end
        
        function gas(app)
            Items = char(app.GasListBox.Items);
            im=app.GasListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                if(app.iunit==1)
                    rho=0.076487;
                    c=1125;
                    app.GasDensitylbmft3EditFieldLabel.Text='Gas Density (lbm/ft^3)';
                    app.SpeedofSoundftsecEditFieldLabel.Text='Speed of Sound (ft/sec)';
                else    
                    rho=1.225;
                    c=343;        
                    app.GasDensitylbmft3EditFieldLabel.Text='Gas Density (kg/m^3)'; 
                    app.SpeedofSoundftsecEditFieldLabel.Text='Speed of Sound (m/sec)';                
                end

                sc=sprintf('%8.4g',c);
                sr=sprintf('%8.4g',rho);               

                app.SpeedofSoundftsecEditField.Value=sc;
                app.GasDensitylbmft3EditField.Value=sr;               
            end    
            
        end
        
        function change(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iunit]=find_index(Items,im);

            gas(app);         
        end
        
        function oadBA(app)
            Items = char(app.SpecifyOverallSPLdBAListBox.Items);
            im=app.SpecifyOverallSPLdBAListBox.Value;
            [app.ioadBA]=find_index(Items,im);  

            if(app.ioadBA==1)
                app.OASPLdBAEditField.Visible='off';
                app.OASPLdBAEditFieldLabel.Visible='off';
            else
                app.OASPLdBAEditField.Visible='on';
                app.OASPLdBAEditFieldLabel.Visible='on';            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);
            oadBA(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                    
            ClosePlots(app);

            fprintf('\n * * * * * * * * \n\n');

            c=str2num(app.SpeedofSoundftsecEditField.Value);
            rho=str2num(app.GasDensitylbmft3EditField.Value);

            meter_per_ft=0.3048;

            if(app.iunit==1)
                c=c*meter_per_ft;
                rho=rho*16.0185;
            end    


            % pressure (dBA) at 3 feet from source
            
            THM=[31.5	71.0	74.0	78.3	81.2	83.1	84.94
                63	    72.1	75.0	79.2	82.2	84.2	86.02
                125	    73.2	76.2	80.1	83.1	85.1	87.23
                250	    75.2	78.3	82.2	85.0	87.0	89.25
                500	    75.2	78.4	82.2	85.2	87.2	89.39
                1000	78.3	81.2	85.1	88.1	90.2	92.10
                2000	75.3	78.2	82.2	85.2	86.9	89.09
                4000	71.1	73.8	78.1	81.2	83.0	85.19
                8000	65.4	68.3	72.2	75.3	77.1	79.34];
    
               f=THM(:,1);
               A=THM(:,2);
               B=THM(:,3);           
               C=THM(:,4);
               D=THM(:,5);
               E=THM(:,6);  
               F=THM(:,7);
    
               num=length(f);
    
               pump_power=str2num(app.PumpPowerEditField.Value);
               rpm=str2num(app.SpeedRPMEditField.Value);
    
               Items = char(app.UnitListBox.Items);
               im=app.UnitListBox.Value;
               [n]=find_index(Items,im);
    
               if(n==2)
                   pump_power=pump_power*1.34102;
               end 

               hp=pump_power;
    
               pressure_ref=20e-06;
               power_ref=1e-12;

               pressure_dB=zeros(num,1);
               power_dB=zeros(num,1);
    
               % SPL
               for i=1:num
                   if(hp<=10)
                       pressure_dB=A;
                   end
                   if(hp>10 && hp<20)
                        x1= 10;
                        y1= A;
                        x2= 20;
                        y2= B;
                        [pressure_dB]=linear_interpolation_function(x1,y1,x2,y2,hp);
                   end    
                   if(hp==20)
                       pressure_dB=B;
                   end
                   if(hp>20 && hp<50)
                        x1= 20;
                        y1= B;
                        x2= 50;
                        y2= C;
                        [pressure_dB]=linear_interpolation_function(x1,y1,x2,y2,hp);
                   end                      
                   if(hp==50)
                       pressure_dB=C;   
                   end
                   if(hp>50 && hp<100)
                        x1= 50;
                        y1= C;
                        x2= 100;
                        y2= D;
                        [pressure_dB]=linear_interpolation_function(x1,y1,x2,y2,hp);
                   end                      
                   if(hp==100)
                       pressure_dB=D;     
                   end
                   if(hp>100 && hp<500)
                        x1= 100;
                        y1= E;
                        x2= 500;
                        y2= F;
                        [pressure_dB]=linear_interpolation_function(x1,y1,x2,y2,hp);
                   end                      
                   if(hp==500)
                       pressure_dB=E;    
                   end
                   if(hp>500 && hp<2000)
                        x1= 500;
                        y1= F;
                        x2= 2000;
                        y2= G;
                        [pressure_dB]=linear_interpolation_function(x1,y1,x2,y2,hp);
                   end                     
                   if(hp>=2000)
                       pressure_dB=G;   
                   end    
               
               end

               % speed correction

               delta=0;
     
               if(rpm<=900)
                   delta=7;
               end    
               if(rpm>900 && rpm<1000)
                   x1= 900;
                   y1= 7;
                   x2= 1000;
                   y2= 5;
                   [delta]=linear_interpolation_function(x1,y1,x2,y2,rpm);
               end    
               if(rpm>=1000 && rpm<=1500)
                   delta=5;
               end    
               if(rpm>1500 && rpm<1600)
                   x1= 1500;
                   y1= 5;
                   x2= 1600;
                   y2= 0;                  
                   [delta]=linear_interpolation_function(x1,y1,x2,y2,rpm);                   
               end                 
               if(rpm>=1600 && rpm<=1800)
                   delta=0;
               end 
               if(rpm>1800 && rpm<3000)
                   x1= 1800;
                   y1= 0;
                   x2= 3000;
                   y2= 3;           
                   [delta]=linear_interpolation_function(x1,y1,x2,y2,rpm);
               end                  
               if(rpm>=3000)
                   delta=3;
               end 

               for i=1:num
                   pressure_dB(i)=pressure_dB(i)-delta;
               end

               %%%

               if(app.ioadBA==2)
                   specified_dBA=str2num(app.OASPLdBAEditField.Value);
                   overall_dB=10*log10(sum(10.^(pressure_dB/10)));

                   ddd=overall_dB-specified_dBA;

                   for i=1:num
                        pressure_dB(i)=pressure_dB(i)-ddd;
                   end                  

               end    

               fig_num=1;

               overall_dB=10*log10(sum(10.^(pressure_dB/10)));
               tstring=sprintf('One Octave Sound Pressure Level  %4.1f dBA Overall \n Ref: 20 micro Pa',overall_dB);
               ylab='SPL (dB)';
               [fig_num]=plot_bar(fig_num,f,pressure_dB,tstring,ylab);               

               %%%

               rhoc=rho*c;
               r=3*meter_per_ft;
               area=2*pi*r^2;
    
               % power level hemisphere
               for i=1:num
                   p=pressure_ref*10^(pressure_dB(i)/20); 
                   intensity=p^2/rhoc;
                   power=intensity*area;
                   power_dB(i)=10*log10(power/power_ref);
               end
    
               overall_dB=10*log10(sum(10.^(power_dB/10)));
    
               fprintf('\n zero dB ref: 1 pico W');
               fprintf('\n\n   fc(Hz) Power(dBA) \n')
               for i=1:num
                    fprintf(' %7.4g  %4.1f \n',f(i),power_dB(i));
               end
    
               fprintf('\n Overall Power Level = %4.1f dBA \n\n',overall_dB);

               tstring=sprintf('One Octave Sound Power Level  %4.1f dBA Overall \n Ref: 1 pico W',overall_dB);
               ylab='Power Level (dB)';
               [fig_num]=plot_bar(fig_num,f,power_dB,tstring,ylab);

               name=app.OutputArrayNameEditField.Value;
               assignin('base',name,[f power_dB]);

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: GasListBox
        function GasListBoxValueChanged(app, event)
            change(app);
        end

        % Value changed function: SpecifyOverallSPLdBAListBox
        function SpecifyOverallSPLdBAListBoxValueChanged(app, event)
            oadBA(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.pump_noise_Appver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.pump_noise_Appver10byTomIrvineLabel.Text = 'pump_noise_App     ver 1.0    by  Tom Irvine';
            app.PumpPowerEditFieldLabel = uilabel(app.UIFigure);
            app.PumpPowerEditFieldLabel.Text = 'Pump Power';
            app.PumpPowerEditField = uieditfield(app.UIFigure, 'text');
            app.UnitListBoxLabel = uilabel(app.UIFigure);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.UIFigure);
            app.UnitListBox.Items = {'hp', 'kW'};
            app.UnitListBox.Value = 'hp';
            app.SpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedRPMEditFieldLabel.Text = 'Speed (RPM)';
            app.SpeedRPMEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Reference:  J. Paul Guyer, An Introduction to Sound Level Data for Mechanical and '; 'Electrical Equipment Course No: M03-027, Credit: 3 PDH'};
            app.OutputArrayNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputArrayNameEditField.Value = 'pump_power_dBA';
            app.GasListBoxLabel = uilabel(app.UIFigure);
            app.GasListBoxLabel.Text = 'Gas';
            app.GasListBox = uilistbox(app.UIFigure);
            app.GasListBox.Items = {'Air', 'Other'};
            app.GasListBox.ValueChangedFcn = createCallbackFcn(app, @GasListBoxValueChanged, true);
            app.GasListBox.Value = 'Air';
            app.GasDensitylbmft3EditFieldLabel = uilabel(app.UIFigure);
            app.GasDensitylbmft3EditFieldLabel.Text = 'Gas Density (lbm/ft^3)';
            app.GasDensitylbmft3EditField = uieditfield(app.UIFigure, 'text');
            app.SpeedofSoundftsecEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedofSoundftsecEditFieldLabel.Text = 'Speed of Sound (ft/sec)';
            app.SpeedofSoundftsecEditField = uieditfield(app.UIFigure, 'text');
            app.UnitsListBoxLabel = uilabel(app.UIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.UIFigure);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.SpecifyOverallSPLdBAListBoxLabel = uilabel(app.UIFigure);
            app.SpecifyOverallSPLdBAListBoxLabel.Text = 'Specify Overall SPL dBA';
            app.SpecifyOverallSPLdBAListBox = uilistbox(app.UIFigure);
            app.SpecifyOverallSPLdBAListBox.Items = {'No', 'Yes'};
            app.SpecifyOverallSPLdBAListBox.ValueChangedFcn = createCallbackFcn(app, @SpecifyOverallSPLdBAListBoxValueChanged, true);
            app.SpecifyOverallSPLdBAListBox.Value = 'No';
            app.OASPLdBAEditFieldLabel = uilabel(app.UIFigure);
            app.OASPLdBAEditFieldLabel.Text = 'OASPL dBA';
            app.OASPLdBAEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pump_noise_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end