classdef rainflow_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RainflowUIFigure                matlab.ui.Figure
        RainflowCycleCountingperASTME1049852017Label  matlab.ui.control.Label
        rainflow_appver15byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RainflowCycleCountingTab        matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel  matlab.ui.control.Label
        ExportRainflowOutputButton      matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        TheinputarraymusthaveeitheroftheseformatsAmplitudeLabel  matlab.ui.control.Label
        RangeCyclesMaxMinEditField      matlab.ui.control.EditField
        RangeCyclesMaxMinLabel          matlab.ui.control.Label
        RangeCyclesEditField            matlab.ui.control.EditField
        RangeCyclesEditFieldLabel       matlab.ui.control.Label
        AmplitudeMeanCyclesEditField    matlab.ui.control.EditField
        AmplitudeMeanCyclesEditFieldLabel  matlab.ui.control.Label
        AmplitudeCyclesEditField        matlab.ui.control.EditField
        AmplitudeCyclesEditFieldLabel   matlab.ui.control.Label
        SavetoWorkspaceLabel            matlab.ui.control.Label
        ExportTableascsvforSpreadsheetLabel  matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        DisplayFigureListBox            matlab.ui.control.ListBox
        DisplayFigureListBoxLabel       matlab.ui.control.Label
        DisplayTableListBox             matlab.ui.control.ListBox
        DisplayTableListBoxLabel        matlab.ui.control.Label
        YaxisLabelEditField             matlab.ui.control.EditField
        YaxisLabelEditFieldLabel        matlab.ui.control.Label
        RelativeDamageTab               matlab.ui.container.Tab
        CalculateButton_Relative        matlab.ui.control.Button
        Label_RD                        matlab.ui.control.Label
        AmplitudeUnitEditField          matlab.ui.control.EditField
        AmplitudeUnitEditFieldLabel     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        FatigueExponentEditField        matlab.ui.control.EditField
        FatigueExponentEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RelativeDamageResultEditField   matlab.ui.control.EditField
        RelativeDamageResultEditFieldLabel  matlab.ui.control.Label
        StressPalmgrenMinerDamageTab    matlab.ui.container.Tab
        StressTimeHistoryArrayEditField  matlab.ui.control.EditField
        StressTimeHistoryArrayEditFieldLabel  matlab.ui.control.Label
        DamageResultsTextArea           matlab.ui.control.TextArea
        DamageResultsTextAreaLabel      matlab.ui.control.Label
        DurationScaleFactorEditField    matlab.ui.control.EditField
        DurationScaleFactorEditFieldLabel  matlab.ui.control.Label
        StressScaleFactorEditField      matlab.ui.control.EditField
        StressScaleFactorEditFieldLabel  matlab.ui.control.Label
        InputStressUnitListBox          matlab.ui.control.ListBox
        InputStressUnitListBoxLabel     matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        MMPDSCoefficientsTab            matlab.ui.container.Tab
        CalculateButton_MMPDS           matlab.ui.control.Button
        PEditField                      matlab.ui.control.EditField
        PEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.EditField
        AEditFieldLabel                 matlab.ui.control.Label
        CoefficientsfromSNcurvewithStressinksiLabel  matlab.ui.control.Label
        UltimateStressLimitpsiEditField  matlab.ui.control.EditField
        UltimateStressLimitpsiEditFieldLabel  matlab.ui.control.Label
        ViewEquationButton              matlab.ui.control.Button
        MaterialLibraryListBox          matlab.ui.control.ListBox
        MaterialLibraryListBoxLabel     matlab.ui.control.Label
        BasquinTab                      matlab.ui.container.Tab
        CalculateButton_Basquin         matlab.ui.control.Button
        FatigueStrengthCoefficientAEditField  matlab.ui.control.EditField
        FatigueStrengthCoefficientAEditFieldLabel  matlab.ui.control.Label
        FatigueExponentMEditField       matlab.ui.control.EditField
        FatigueExponentMEditFieldLabel  matlab.ui.control.Label
        unitLabel                       matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        kflag           % Description
        THM             %
        amp_cycles      %
        amp_mean_cycles %
        range_cycles    %
        range_cycles_max_min  %
        BIG             %
        columnname      %
        TQ              %
        mlab            %
        FS              % 
        tau             %  
        imat            %
        mlabB           %  
    end
    
    methods (Access = private)

        function materials(app)
            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);

            n_mat=app.imat;

            Items = char(app.InputStressUnitListBox.Items);
            im=app.InputStressUnitListBox.Value;
            [n]=find_index(Items,im);

            app.mlabB='other'; 
             
            if(n_mat==1) % aluminum 6061-T6
                m=9.25; 
                app.mlabB='aluminum 6061-T6';
            end
            if(n_mat==2) % butt-welded steel
                m=3.5;
                app.mlabB='butt-welded steel';
            end    
            if(n_mat==3) % stainless steel
                m=6.54;
                app.mlabB='stainless steel';
            end
             
            if(n_mat==4) % Petrucci: aluminum
                m=7.3; 
                app.mlabB='Petrucci: aluminum 2219-T851';
            end
            if(n_mat==5) % Petrucci: steel
                m=3.324;
                app.mlabB='Petrucci: steel';    
            end    
            if(n_mat==6) % Petrucci: spring steel
                m=11.760;
                app.mlabB='Petrucci: spring steel';       
            end
             
            if(n_mat==1)  % aluminum 6061-T6
             
                Aksi=9.7724e+17;
                AMPa=5.5757e+25;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m;        
                end   
                if(n==4)
                    A=AMPa;        
                end
                
            end
            if(n_mat==2)  % butt-welded steel
             
                Aksi=1.255e+11;
                AMPa=1.080e+14;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m;        
                end             
                if(n==4)
                    A=AMPa;      
                end    
                
            end
            if(n_mat==3)  % stainless steel
               
                Aksi=1.32E+18;
                AMPa=4.0224e+23;  
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m;         
                end 
                if(n==4)
                    A=AMPa;      
                end        
                
            end
            if(n_mat==4)  % Petrucci: aluminum
               
                Aksi=5.18E+13;
                AMPa=6.85E+19;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m;         
                end            
                if(n==4)
                    A=AMPa;      
                end        
                
            end
            if(n_mat==5)  % Petrucci: steel
               
                Aksi=3.16E+09;
                AMPa=1.93E+12;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m;         
                end           
                if(n==4)
                    A=AMPa;      
                end        
                
            end
            if(n_mat==6)  % Petrucci: spring steel
               
                Aksi=1.95E+27;
                AMPa=1.41E+37;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=((AMPa^(1/m))*1000)^m; 
                end           
                if(n==4)
                    A=AMP;      
                end        
                
            end
             
             
            if(n_mat<=6)
                ms=sprintf('%g',m);    
                As=sprintf('%8.4g',A);
                
                
                if(n==1)
                    ss=sprintf('psi^%g',m);
                end
                if(n==2)
                    ss=sprintf('ksi^%g',m);        
                end
                if(n==3)
                    ss=sprintf('Pa^m');        
                end           
                if(n==4)
                    ss=sprintf('MPa^%g',m);        
                end
                
            else
                ms='';    
                As='';    
            
                if(n==1)
                    ss=sprintf('psi^m');
                end
                if(n==2)
                    ss=sprintf('ksi^m');        
                end
                if(n==3)
                    ss=sprintf('Pa^m');        
                end            
                if(n==4)
                    ss=sprintf('MPa^m');        
                end
               
            end    

            app.unitLabel.Text=ss;

            app.FatigueExponentMEditField.Value=ms;
            app.FatigueStrengthCoefficientAEditField.Value=As;

        end    


        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end       
        
        function update(app)
            [cell_array]=update_two_columns();
            app.kflag=0;  
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));          
        end
        
        
        function ChangeMMPDS(app)

            ClearDamageResults(app);

            Items = char(app.MaterialLibraryListBox.Items);
            im=app.MaterialLibraryListBox.Value;
            [n]=find_index(Items,im);

            % 1. Aluminum 6061-T6
            % 2. Aluminum 7050-T7451
            % 3. Aluminum 7075-T6
            % 4. Inconel 718
            % 5. Stainless Steel Custom 450 (H1050)
            % 6. Stainless Steel 15-5PH, 1025 
            
            [app.mlab,As,Bs,Cs,Ps]=SN_coefficients(n);
            
            app.AEditField.Value=As;
            app.BEditField.Value=Bs;            
            app.CEditField.Value=Cs;  
            app.PEditField.Value=Ps;  
            
            Items = char(app.InputStressUnitListBox.Items);
            im=app.InputStressUnitListBox.Value;
            [m]=find_index(Items,im);
  
            if(m==1)
                sss='Ultimate Stress Limit (psi)';
            end
            if(m==2)
                sss='Ultimate Stress Limit (ksi)';
            end
            if(m==3)
                sss='Ultimate Stress Limit (Pa)';
            end
            if(m==4)
                sss='Ultimate Stress Limit (MPa)';
            end

            app.UltimateStressLimitpsiEditFieldLabel.Text=sss;
            
            app.UltimateStressLimitpsiEditField.Value=sprintf(' ');           
            
            if(n==1) % Aluminum 6061-T6
                USL=42000;
            end
            if(n==2) % Aluminum 7050-T7451
                USL=76000;
            end
            if(n==3) % Aluminum 7075-T6
                USL=74000;
            end
            if(n==4)  % Inconel 718
                USL=199000;
            end
            if(n==5)  % Stainless Steel Custom 450 (H1050)
                USL=160000;
            end
            if(n==6) % Stainless Steel 15-5PH, 1025 
                USL=155000;
            end
            
            
            if(m==2) % ksi
                USL=USL/1000;
            end
            if(m==3) % Pa
                USL=USL*6891.2;
            end
            if(m==4) % MPa
                USL=USL*6891.2/1.0e+06;
            end
            
            if(n<=6)
                ss=sprintf('%8.5g',USL);
                app.UltimateStressLimitpsiEditField.Value=ss;  
            end            
        end
        
        function ClearDamageResults(app)
            app.DamageResultsTextArea.Value=sprintf(' ');
            app.DamageResultsTextArea.Enable='off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RelativeDamageResultEditField.Enable='off';
            update(app);
            ChangeMMPDS(app);
            ClosePlots(app);
            materials(app);
            app.tau=0;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             
            app.RelativeDamageResultEditField.Enable='off';

                if(app.kflag==1)
                    warndlg('No arrays available');
                    return;
                end    

                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
             
                if(n==1)
    
                     try
                         app.FS=app.SelectInputArrayListBox.Value;
                         app.THM=evalin('base',app.FS);
                     catch
                         warndlg('Input Array does not exist.  Try reading in external file.')
                         return;
                     end
                end

                YS=app.YaxisLabelEditField.Value;

                sz=size(app.THM);

                if(sz(2)==1)
                    y=app.THM;
                else
                    y=app.THM(:,2);
                    app.tau=app.THM(end,1)-app.THM(1,1);
                end    

                if(max(y)==0  && min(y)==0)
                    warndlg('Input amplitude is zero')
                    return;
                end    


                Items = char(app.DisplayTableListBox.Items);
                im=app.DisplayTableListBox.Value;
                [ndt]=find_index(Items,im);
 
                Items = char(app.DisplayFigureListBox.Items);
                im=app.DisplayFigureListBox.Value;
                [ndf]=find_index(Items,im);               

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                [app.amp_cycles,app.amp_mean_cycles,app.range_cycles_max_min,app.range_cycles,app.BIG]=...
                                                 rainflow_numerical_engine_alt(y,YS,ndf,app.THM);  
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                if(ndt==1)
                %
                    figure(3)
                    hFig = figure(3);
                    xwidth=900;
                    ywidth=400;
                    set(gcf,'PaperPositionMode','auto')
                    set(hFig, 'Position', [0 0 xwidth ywidth])
                    table1 = uitable;
                    set(table1,'ColumnWidth',{27})
                
                    dat =  app.BIG(:,1:10);
                    app.columnname =   {'Lower Range','Upper Range','Cycles','Ave Amp',...
                       'Max Amp','Min Mean','Ave Mean','Max Mean','Min Valley','Max Peak' };
                    columnformat = {'numeric', 'numeric','numeric','numeric','numeric',...
                       'numeric','numeric','numeric','numeric','numeric'};
                    columneditable = [false false false false false false false false false false];   
                
                    sz=size(app.BIG);
                %
                    clear data_s;
                    clear app.TQ;

                    for i = 1:sz(1)
                        for j=1:sz(2)
                            if(j==3)
                                tempStr = sprintf('%10.1f', dat(i,j));                
                            else
                                tempStr = sprintf('%8.5g', dat(i,j));
                            end    
                            data_s{i,j} = tempStr;     
                        end  
                    end
                %
                    try
                        table1 = uitable('Units','normalized','Position',...
                            [0 0 1 1], 'Data', data_s,... 
                            'ColumnName', app.columnname,...
                            'ColumnFormat', columnformat,...
                            'ColumnEditable', columneditable,...
                            'RowName',[]);
                    catch
                    end

                    app.TQ{1,1}='Lower Range';
                    app.TQ{1,2}='Upper Range';
                    app.TQ{1,3}='Cycles';
                    app.TQ{1,4}='Ave Amp';
                    app.TQ{1,5}='Max Amp';
                    app.TQ{1,6}='Min Mean';
                    app.TQ{1,7}='Ave Mean';
                    app.TQ{1,8}='Max Mean';
                    app.TQ{1,9}='Min Valley';
                    app.TQ{1,10}='Max Peak';
                 
                    for i = 1:sz(1)
                        for j=1:sz(2)
                            if(j==3)
                                tempStr = sprintf('%10.1f', dat(i,j));                
                            else
                                tempStr = sprintf('%8.5g', dat(i,j));
                            end    
                            app.TQ{i+1,j} = tempStr;     
                        end  
                    end                  

                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                try
                    n1=app.AmplitudeCyclesEditField.Value;
                    assignin('base',n1,app.amp_cycles);
                catch
                end    

                try
                    n2=app.AmplitudeMeanCyclesEditField.Value;
                    assignin('base',n2,app.amp_mean_cycles); 
                catch         
                end

                try
                    n3=app.RangeCyclesEditField.Value;
                    assignin('base',n3,app.range_cycles);
                catch
                end

                try
                    n4=app.RangeCyclesMaxMinEditField.Value;
                    assignin('base',n4,app.range_cycles_max_min);
                catch
                end

                msgbox(' Calculation complete. ');
           
           
        end

        % Value changing function: FatigueExponentEditField
        function FatigueExponentEditFieldValueChanging(app, event)
            app.RelativeDamageResultEditField.Enable='off';
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   app.FS=filename;
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            [writefname, writepname] = uiputfile('*.xls','Save table as Excel file');
            writepfname = fullfile(writepname, writefname);
                
            writecell(app.TQ,writepfname);
            
            msgbox('Save Complete'); 

        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            A = imread('nasgro_coefficients.jpg');
            figure(555);
            imshow(A,'border','tight','InitialMagnification',100);
        end

        % Value changed function: InputStressUnitListBox
        function InputStressUnitListBoxValueChanged(app, event)
            ChangeMMPDS(app);
        end

        % Value changed function: MaterialLibraryListBox
        function MaterialLibraryListBoxValueChanged(app, event)
            ChangeMMPDS(app);
        end

        % Value changing function: StressScaleFactorEditField
        function StressScaleFactorEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Value changing function: DurationScaleFactorEditField
        function DurationScaleFactorEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Value changing function: UltimateStressLimitpsiEditField
        function UltimateStressLimitpsiEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Value changing function: AEditField
        function AEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Value changing function: BEditField
        function BEditFieldValueChanging(app, event)
            ClearDamageResults(app);     
        end

        % Value changing function: CEditField
        function CEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Value changing function: PEditField
        function PEditFieldValueChanging(app, event)
            ClearDamageResults(app);
        end

        % Button pushed function: ExportRainflowOutputButton
        function ExportRainflowOutputButtonPushed(app, event)
            writepname = uigetdir();
                try
                    n1=app.AmplitudeCyclesEditField.Value;
                    n1 = fullfile(writepname, n1);
                    writematrix(app.amp_cycles,n1,'Delimiter','\t');
                catch
                end    

                try
                    n2=app.AmplitudeMeanCyclesEditField.Value;
                    n2 = fullfile(writepname, n2); 
                    writematrix(app.amp_mean_cycles,n2,'Delimiter','\t');
                catch         
                end

                try
                    n3=app.RangeCyclesEditField.Value;
                    n3 = fullfile(writepname, n3);
                    writematrix(app.range_cycles,n3,'Delimiter','\t');
                catch
                end

                try
                    n4=app.RangeCyclesMaxMinEditField.Value;
                    n4 = fullfile(writepname, n4);
                    writematrix(app.range_cycles_max_min,n4,'Delimiter','\t');
                catch
                end

                msgbox('Export complete');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.StressTimeHistoryArrayEditField.Value=app.AmplitudeMeanCyclesEditField.Value;
        end

        % Callback function: not associated with a component
        function MaterialListBoxValueChanged(app, event)
           
            materials(app);
        end

        % Button pushed function: CalculateButton_Relative
        function CalculateButton_RelativePushed(app, event)
             D=0;

                b=str2num(app.FatigueExponentEditField.Value);
                
                sz=size(app.range_cycles);
                
                for i=1:sz(1)
                    D=D+app.range_cycles(i,2)*( 0.5*app.range_cycles(i,1) )^b;
                end    
                
                string=sprintf('%8.4g',D);
                
                app.RelativeDamageResultEditField.Value=string;
                app.RelativeDamageResultEditField.Enable='on';

                yy=app.AmplitudeUnitEditField.Value;

                yLL=sprintf('%s^%g',yy,b);

                app.Label_RD.Text=yLL;

        end

        % Button pushed function: CalculateButton_MMPDS
        function CalculateButton_MMPDSPushed(app, event)
                aaa=app.amp_mean_cycles;
        
                disp('((((')

                max(app.amp_mean_cycles(:,1))

                sz=size(aaa);

                if(sz(1)>=2)
                else    
                    warndlg('Perform Rainflow Cycle Counting first');
                    return;
                end    

                scf=str2num(app.StressScaleFactorEditField.Value);

                A=str2num(app.AEditField.Value);
                B=str2num(app.BEditField.Value);
                C=str2num(app.CEditField.Value);
                P=str2num(app.PEditField.Value);
                
                if(B<0)
                    warndlg(' B coefficient should be > 0 '); 
                    return;
                end

                try
                    aaa(:,1)=scf*aaa(:,1);
                catch
                    warndlg('* Run Rainflow Cycle Count first');
                    return;
                end

                Items = char(app.InputStressUnitListBox.Items);
                im=app.InputStressUnitListBox.Value;
                [iu]=find_index(Items,im);
                
                USL=str2num(app.UltimateStressLimitpsiEditField.Value);

                Pa_per_psi = 6894.;

                if(iu==1) % psi
                   disp('** psi **') 
                   USL=USL/1000;
                   aaa(:,1:2)=aaa(:,1:2)/1000;
                end
                if(iu==2) % ksi    
 
                end
                if(iu==3) % Pa
                   USL=USL/Pa_per_psi; 
                   aaa(:,1:2)=aaa(:,1:2)/Pa_per_psi; 
                end
                if(iu==4) % MPa
                   USL=USL*1.0e+06/Pa_per_psi;
                   aaa(:,1:2)=aaa(:,1:2)*1.0e+06/Pa_per_psi;    
                end
                
                if(max(abs(aaa(:,1)))>0.95*USL)
                    warndlg('Warning:  Maximum stress > 0.95 * Ultimate Stress Limit ');
                    return;
                end

                try
                    [damage]=rainflow_stress_damage_nasgro(aaa,A,B,C,P);
                catch
                    warndlg('Damage calculation failed. Check amp_mean_cycles array.');
                    return;
                end

                try
                    sz=size(app.THM);
                catch
                    warndlg('No time history input file from Rainflow Cycle Count');
                    return;
                end

                s1=sprintf('Material: %s ',app.mlab);
                s2=sprintf('A=%g  B=%g  C=%g  P=%g ',A,B,C,P);
                
                ss=sprintf('%s\n%s\n',s1,s2);

                if(app.tau>0)
                    tscale=str2num(app.DurationScaleFactorEditField.Value);
                    s3=sprintf('Input Duration=%8.4g sec  \nScaled Duration=%8.4g sec',app.tau,app.tau*tscale);
                    ss=sprintf('%s\n%s',ss,s3);                   
                end

                s5=sprintf('Maximum Rainflow Stress Amplitude \n with scale factor =%8.4g ksi ',max( aaa(:,1) ));
                
                s6=sprintf('Number of Rainflow Cycles=%8.4g ',sum(app.amp_mean_cycles(:,3)));
                
                s7=sprintf('Stress Scale Factor=%g \nDuration Scale Factor=%g',scf,tscale);

                YS=app.YaxisLabelEditField.Value;
                plot(app.THM(:,1),app.THM(:,2)*scf);
                grid on;
                ylabel(YS);
                xlabel('Time (sec)');
                sss=sprintf('Stress Time History, Scale Factor = %d',scf);
                title(sss);
                
                s8=sprintf('Cumulative Damage=%8.4g ',damage*tscale);
                
                ss=sprintf('%s \n\n%s \n\n%s \n\n%s \n\n%s \n',ss,s5,s6,s7,s8);

                if(sz(2)==2)
                    s9=sprintf('Cumulative Damage Rate=\n %8.4g damage/sec\n',damage/app.tau);
                    ss=sprintf('%s \n%s',ss,s9);
                end
               
                app.DamageResultsTextArea.Enable='on';
                app.DamageResultsTextArea.Value=ss;
                msgbox('Results written to command window.'); 

           
        end

        % Button pushed function: CalculateButton_Basquin
        function CalculateButton_BasquinPushed(app, event)
               aaa=app.amp_mean_cycles;
        
                disp('((((')

                max(app.amp_mean_cycles(:,1))

                sz=size(aaa);

                if(sz(1)>=2)
                else    
                    warndlg('Perform Rainflow Cycle Counting first');
                    return;
                end    

                scf=str2num(app.StressScaleFactorEditField.Value);

                 try
                    aaa(:,1)=scf*aaa(:,1);
                catch
                    warndlg('* Run Rainflow Cycle Count first');
                    return;
                end

                n=aaa(:,3);

                b=str2num(app.FatigueExponentMEditField.Value);
                A=str2num(app.FatigueStrengthCoefficientAEditField.Value);
               
                D=0;
                
                for i=1:sz(1)
                
                    D=D+n(i)*(aaa(i,1))^b;
                    
                end
                
                damage=D/A;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                s1=sprintf('Material: %s \n',app.mlabB);
                
                ss=s1;

                if(app.tau>0)
                    tscale=str2num(app.DurationScaleFactorEditField.Value);
                    s3=sprintf('Input Duration=%8.4g sec  \nScaled Duration=%8.4g sec',app.tau,app.tau*tscale);
                    ss=sprintf('%s\n%s',ss,s3);                   
                end

                s5=sprintf('Maximum Rainflow Stress Amplitude \n with scale factor =%8.4g ksi ',max( aaa(:,1) ));
                
                s6=sprintf('Number of Rainflow Cycles=%8.4g ',sum(app.amp_mean_cycles(:,3)));
                
                s7=sprintf('Stress Scale Factor=%g \nDuration Scale Factor=%g',scf,tscale);

                YS=app.YaxisLabelEditField.Value;
                plot(app.THM(:,1),app.THM(:,2)*scf);
                grid on;
                ylabel(YS);
                xlabel('Time (sec)');
                sss=sprintf('Stress Time History, Scale Factor = %d',scf);
                title(sss);
                
                s8=sprintf('Cumulative Damage=%8.4g ',damage*tscale);
                
                ss=sprintf('%s \n\n%s \n\n%s \n\n%s \n\n%s \n',ss,s5,s6,s7,s8);

                s9=sprintf('Cumulative Damage Rate=\n %8.4g damage/sec\n',damage/app.tau);
                ss=sprintf('%s \n%s',ss,s9);
                               
                app.DamageResultsTextArea.Enable='on';
                app.DamageResultsTextArea.Value=ss;
                msgbox('Results written to command window.'); 

        end

        % Button down function: MMPDSCoefficientsTab
        function MMPDSCoefficientsTabButtonDown(app, event)
            app.DamageResultsTextArea.Value='';
        end

        % Button down function: BasquinTab
        function BasquinTabButtonDown(app, event)
            app.DamageResultsTextArea.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RainflowUIFigure = uifigure('Visible', 'off');
            app.RainflowUIFigure.Name = 'Rainflow';
            app.TabGroup = uitabgroup(app.RainflowUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RainflowCycleCountingTab = uitab(app.TabGroup);
            app.RainflowCycleCountingTab.Title = 'Rainflow Cycle Counting';
            app.YaxisLabelEditFieldLabel = uilabel(app.RainflowCycleCountingTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.RainflowCycleCountingTab, 'text');
            app.DisplayTableListBoxLabel = uilabel(app.RainflowCycleCountingTab);
            app.DisplayTableListBoxLabel.Text = 'Display Table';
            app.DisplayTableListBox = uilistbox(app.RainflowCycleCountingTab);
            app.DisplayTableListBox.Items = {'Yes', 'No'};
            app.DisplayTableListBox.Value = 'Yes';
            app.DisplayFigureListBoxLabel = uilabel(app.RainflowCycleCountingTab);
            app.DisplayFigureListBoxLabel.Text = 'Display Figure';
            app.DisplayFigureListBox = uilistbox(app.RainflowCycleCountingTab);
            app.DisplayFigureListBox.Items = {'Yes', 'No'};
            app.DisplayFigureListBox.Value = 'Yes';
            app.ExportButton = uibutton(app.RainflowCycleCountingTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportTableascsvforSpreadsheetLabel = uilabel(app.RainflowCycleCountingTab);
            app.ExportTableascsvforSpreadsheetLabel.Text = {'Export Table as csv '; 'for Spreadsheet'};
            app.SavetoWorkspaceLabel = uilabel(app.RainflowCycleCountingTab);
            app.SavetoWorkspaceLabel.Text = 'Save to Workspace';
            app.AmplitudeCyclesEditFieldLabel = uilabel(app.RainflowCycleCountingTab);
            app.AmplitudeCyclesEditFieldLabel.Text = 'Amplitude & Cycles';
            app.AmplitudeCyclesEditField = uieditfield(app.RainflowCycleCountingTab, 'text');
            app.AmplitudeCyclesEditField.Value = 'amp_cycles';
            app.AmplitudeMeanCyclesEditFieldLabel = uilabel(app.RainflowCycleCountingTab);
            app.AmplitudeMeanCyclesEditFieldLabel.Text = 'Amplitude, Mean & Cycles';
            app.AmplitudeMeanCyclesEditField = uieditfield(app.RainflowCycleCountingTab, 'text');
            app.AmplitudeMeanCyclesEditField.Value = 'amp_mean_cycles';
            app.RangeCyclesEditFieldLabel = uilabel(app.RainflowCycleCountingTab);
            app.RangeCyclesEditFieldLabel.Text = 'Range & Cycles';
            app.RangeCyclesEditField = uieditfield(app.RainflowCycleCountingTab, 'text');
            app.RangeCyclesEditField.Value = 'range_cycles';
            app.RangeCyclesMaxMinLabel = uilabel(app.RainflowCycleCountingTab);
            app.RangeCyclesMaxMinLabel.Text = 'Range, Cycles, Max, Min';
            app.RangeCyclesMaxMinEditField = uieditfield(app.RainflowCycleCountingTab, 'text');
            app.RangeCyclesMaxMinEditField.Value = 'range_cycles_max_min';
            app.TheinputarraymusthaveeitheroftheseformatsAmplitudeLabel = uilabel(app.RainflowCycleCountingTab);
            app.TheinputarraymusthaveeitheroftheseformatsAmplitudeLabel.Text = {'The input array must have either of these formats:  '; ''; '  Amplitude'; '  Time & Amplitude     '; ''; 'Note that the amplitude in the resulting table is:  '; '    (peak-valley)/2'};
            app.listbox_method = uilistbox(app.RainflowCycleCountingTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.RainflowCycleCountingTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.RainflowCycleCountingTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.RainflowCycleCountingTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.RainflowCycleCountingTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.ExportRainflowOutputButton = uibutton(app.RainflowCycleCountingTab, 'push');
            app.ExportRainflowOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRainflowOutputButtonPushed, true);
            app.ExportRainflowOutputButton.Text = 'Export Rainflow Output';
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel = uilabel(app.RainflowCycleCountingTab);
            app.TheExportFoldermustbeoutsideofthevibrationdata_altfolderLabel.Text = {'The Export Folder must be outside of the'; 'vibrationdata_alt folder'};
            app.CalculateButton = uibutton(app.RainflowCycleCountingTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RelativeDamageTab = uitab(app.TabGroup);
            app.RelativeDamageTab.Title = 'Relative Damage';
            app.RelativeDamageResultEditFieldLabel = uilabel(app.RelativeDamageTab);
            app.RelativeDamageResultEditFieldLabel.Text = 'Relative Damage Result';
            app.RelativeDamageResultEditField = uieditfield(app.RelativeDamageTab, 'text');
            app.Label = uilabel(app.RelativeDamageTab);
            app.Label.Text = 'The input array is the range-cycles array from the previous Rainflow Cycle Count tab.';
            app.FatigueExponentEditFieldLabel = uilabel(app.RelativeDamageTab);
            app.FatigueExponentEditFieldLabel.Text = 'Fatigue Exponent';
            app.FatigueExponentEditField = uieditfield(app.RelativeDamageTab, 'text');
            app.FatigueExponentEditField.ValueChangingFcn = createCallbackFcn(app, @FatigueExponentEditFieldValueChanging, true);
            app.FatigueExponentEditField.Value = '4';
            app.Label_2 = uilabel(app.RelativeDamageTab);
            app.Label_2.Text = '  ';
            app.AmplitudeUnitEditFieldLabel = uilabel(app.RelativeDamageTab);
            app.AmplitudeUnitEditFieldLabel.Text = 'Amplitude Unit';
            app.AmplitudeUnitEditField = uieditfield(app.RelativeDamageTab, 'text');
            app.AmplitudeUnitEditField.Value = 'G';
            app.Label_RD = uilabel(app.RelativeDamageTab);
            app.CalculateButton_Relative = uibutton(app.RelativeDamageTab, 'push');
            app.CalculateButton_Relative.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_RelativePushed, true);
            app.CalculateButton_Relative.Text = 'Calculate';
            app.StressPalmgrenMinerDamageTab = uitab(app.TabGroup);
            app.StressPalmgrenMinerDamageTab.Title = 'Stress Palmgren-Miner Damage';
            app.Label_3 = uilabel(app.StressPalmgrenMinerDamageTab);
            app.Label_3.Text = 'The input array is taken from the Rainflow Cycle Count  which must be performed first.';
            app.TabGroup2 = uitabgroup(app.StressPalmgrenMinerDamageTab);
            app.MMPDSCoefficientsTab = uitab(app.TabGroup2);
            app.MMPDSCoefficientsTab.Title = 'MMPDS Coefficients';
            app.MMPDSCoefficientsTab.ButtonDownFcn = createCallbackFcn(app, @MMPDSCoefficientsTabButtonDown, true);
            app.MaterialLibraryListBoxLabel = uilabel(app.MMPDSCoefficientsTab);
            app.MaterialLibraryListBoxLabel.Text = 'Material Library';
            app.MaterialLibraryListBox = uilistbox(app.MMPDSCoefficientsTab);
            app.MaterialLibraryListBox.Items = {'Aluminum 6061-T6, Kt=1.0', 'Aluminum 7050-T7451, Kt=1.0', 'Aluminum 7075-T6, Kt=1.0', 'Inconel 718, Kt=1.0', 'Stainless Steel Custom 450 (H1050), Kt=3.0', 'Stainless Steel 15-5PH 1025, Kt=3.0', 'Other      '};
            app.MaterialLibraryListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialLibraryListBoxValueChanged, true);
            app.MaterialLibraryListBox.Value = 'Aluminum 6061-T6, Kt=1.0';
            app.ViewEquationButton = uibutton(app.MMPDSCoefficientsTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.UltimateStressLimitpsiEditFieldLabel = uilabel(app.MMPDSCoefficientsTab);
            app.UltimateStressLimitpsiEditFieldLabel.Text = 'Ultimate Stress Limit (psi)';
            app.UltimateStressLimitpsiEditField = uieditfield(app.MMPDSCoefficientsTab, 'text');
            app.UltimateStressLimitpsiEditField.ValueChangingFcn = createCallbackFcn(app, @UltimateStressLimitpsiEditFieldValueChanging, true);
            app.CoefficientsfromSNcurvewithStressinksiLabel = uilabel(app.MMPDSCoefficientsTab);
            app.CoefficientsfromSNcurvewithStressinksiLabel.Text = {'Coefficients from S/N curve '; 'with Stress in ksi'};
            app.AEditFieldLabel = uilabel(app.MMPDSCoefficientsTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.MMPDSCoefficientsTab, 'text');
            app.AEditField.ValueChangingFcn = createCallbackFcn(app, @AEditFieldValueChanging, true);
            app.BEditFieldLabel = uilabel(app.MMPDSCoefficientsTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.MMPDSCoefficientsTab, 'text');
            app.BEditField.ValueChangingFcn = createCallbackFcn(app, @BEditFieldValueChanging, true);
            app.CEditFieldLabel = uilabel(app.MMPDSCoefficientsTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.MMPDSCoefficientsTab, 'text');
            app.CEditField.ValueChangingFcn = createCallbackFcn(app, @CEditFieldValueChanging, true);
            app.PEditFieldLabel = uilabel(app.MMPDSCoefficientsTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.MMPDSCoefficientsTab, 'text');
            app.PEditField.ValueChangingFcn = createCallbackFcn(app, @PEditFieldValueChanging, true);
            app.CalculateButton_MMPDS = uibutton(app.MMPDSCoefficientsTab, 'push');
            app.CalculateButton_MMPDS.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_MMPDSPushed, true);
            app.CalculateButton_MMPDS.Text = 'Calculate';
            app.BasquinTab = uitab(app.TabGroup2);
            app.BasquinTab.Title = 'Basquin';
            app.BasquinTab.ButtonDownFcn = createCallbackFcn(app, @BasquinTabButtonDown, true);
            app.MaterialListBoxLabel = uilabel(app.BasquinTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.BasquinTab);
            app.MaterialListBox.Items = {'Aluminum 6061-T6', 'Butt-welded Steel Joint', 'Stainless Steel, PH13-8Mo(H1000)', 'Petrucci: Alumimum 2219-T851', 'Petrucci: Steel', 'Petrucci: Spring Steel', 'Other'};
            app.MaterialListBox.Value = 'Aluminum 6061-T6';
            app.unitLabel = uilabel(app.BasquinTab);
            app.unitLabel.Text = 'unit';
            app.FatigueExponentMEditFieldLabel = uilabel(app.BasquinTab);
            app.FatigueExponentMEditFieldLabel.Text = 'Fatigue Exponent M';
            app.FatigueExponentMEditField = uieditfield(app.BasquinTab, 'text');
            app.FatigueStrengthCoefficientAEditFieldLabel = uilabel(app.BasquinTab);
            app.FatigueStrengthCoefficientAEditFieldLabel.Text = 'Fatigue Strength Coefficient A';
            app.FatigueStrengthCoefficientAEditField = uieditfield(app.BasquinTab, 'text');
            app.CalculateButton_Basquin = uibutton(app.BasquinTab, 'push');
            app.CalculateButton_Basquin.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_BasquinPushed, true);
            app.CalculateButton_Basquin.Text = 'Calculate';
            app.InputStressUnitListBoxLabel = uilabel(app.StressPalmgrenMinerDamageTab);
            app.InputStressUnitListBoxLabel.Text = 'Input Stress Unit';
            app.InputStressUnitListBox = uilistbox(app.StressPalmgrenMinerDamageTab);
            app.InputStressUnitListBox.Items = {'psi', 'ksi', 'Pa', 'MPa'};
            app.InputStressUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InputStressUnitListBoxValueChanged, true);
            app.InputStressUnitListBox.Value = 'psi';
            app.StressScaleFactorEditFieldLabel = uilabel(app.StressPalmgrenMinerDamageTab);
            app.StressScaleFactorEditFieldLabel.Text = 'Stress Scale Factor';
            app.StressScaleFactorEditField = uieditfield(app.StressPalmgrenMinerDamageTab, 'text');
            app.StressScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @StressScaleFactorEditFieldValueChanging, true);
            app.StressScaleFactorEditField.Value = '1';
            app.DurationScaleFactorEditFieldLabel = uilabel(app.StressPalmgrenMinerDamageTab);
            app.DurationScaleFactorEditFieldLabel.Text = 'Duration Scale Factor';
            app.DurationScaleFactorEditField = uieditfield(app.StressPalmgrenMinerDamageTab, 'text');
            app.DurationScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @DurationScaleFactorEditFieldValueChanging, true);
            app.DurationScaleFactorEditField.Value = '1';
            app.DamageResultsTextAreaLabel = uilabel(app.StressPalmgrenMinerDamageTab);
            app.DamageResultsTextAreaLabel.Text = 'Damage Results';
            app.DamageResultsTextArea = uitextarea(app.StressPalmgrenMinerDamageTab);
            app.StressTimeHistoryArrayEditFieldLabel = uilabel(app.StressPalmgrenMinerDamageTab);
            app.StressTimeHistoryArrayEditFieldLabel.Text = 'Stress Time History Array';
            app.StressTimeHistoryArrayEditField = uieditfield(app.StressPalmgrenMinerDamageTab, 'text');
            app.StressTimeHistoryArrayEditField.Editable = 'off';
            app.ReturnButton = uibutton(app.RainflowUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.rainflow_appver15byTomIrvineLabel = uilabel(app.RainflowUIFigure);
            app.rainflow_appver15byTomIrvineLabel.Text = 'rainflow_app   ver 1.5   by Tom Irvine';
            app.RainflowCycleCountingperASTME1049852017Label = uilabel(app.RainflowUIFigure);
            app.RainflowCycleCountingperASTME1049852017Label.Text = 'Rainflow Cycle Counting per ASTM E1049-85 (2017).';
            % Show the figure after all components are created
            app.RainflowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rainflow_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RainflowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RainflowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RainflowUIFigure)
        end
    end
end