classdef random_skewness_kurtosis_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SkewnessListBox                matlab.ui.control.ListBox
        SkewnessListBoxLabel           matlab.ui.control.Label
        KurtosisEditField              matlab.ui.control.EditField
        KurtosisEditFieldLabel         matlab.ui.control.Label
        SampleRate10Label              matlab.ui.control.Label
        LPFilterFreqiuencyLabel        matlab.ui.control.Label
        SDOFResponsetoBaseInputButton  matlab.ui.control.Button
        LowpassFilteringListBox        matlab.ui.control.ListBox
        LowpassFilteringListBoxLabel   matlab.ui.control.Label
        RandomNumberSeedListBox        matlab.ui.control.ListBox
        RandomNumberSeedLabel          matlab.ui.control.Label
        OutputNameEditField            matlab.ui.control.EditField
        OutputNameEditFieldLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        YaxisLabelEditField            matlab.ui.control.EditField
        YaxisLabelEditFieldLabel       matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SampleRateHzEditField          matlab.ui.control.EditField
        SampleRateHzEditFieldLabel     matlab.ui.control.Label
        DurationsecEditField           matlab.ui.control.EditField
        DurationsecEditFieldLabel      matlab.ui.control.Label
        StdDevEditField                matlab.ui.control.EditField
        StdDevEditFieldLabel           matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        random_skewness_kurtosis_aver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        nf  %
    end
    
    methods (Access = private)
        
        function clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.OutputNameEditFieldLabel.Visible='off';
            app.OutputNameEditField.Visible='off';
            app.SaveButton.Visible='off';
            try
                app.SDOFResponsetoBaseInputButton.Visible='off';
            catch
                warndlg('SDOF Button failed');
            end
        end
        
        function ChangeFilter(app)
            Items = char(app.LowpassFilteringListBox.Items);
            im=app.LowpassFilteringListBox.Value;
            [app.nf]=find_index(Items,im);          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                clear(app);
            catch
                warndlg('clear failed');
            end
            try
                ChangeFilter(app);
            catch
                warndlg('change filter failed');
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            sigma=str2num(app.StdDevEditField.Value);
            tmax=str2num(app.DurationsecEditField.Value);
            sr=str2num(app.SampleRateHzEditField.Value); 

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            Items = char(app.SkewnessListBox.Items);
            im=app.SkewnessListBox.Value;
            [n]=find_index(Items,im);

            skgoal = str2num(app.SkewnessListBox.Value);
            ktgoal = str2num(app.KurtosisEditField.Value);       

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);

            %%%%%

            num=np;

            skerror=1.0e+20;
            kterror=skerror;

            x=zeros(num,1);
            
            for i=1:num      
                x(i)=(i-1)*dt;
            end
            
            if(app.nf==1)
            %
            %    iband:   1=lowpass  2=highpass  3=bandpass 
            %
            %    iphase=1  for refiltering for phase correction
            %          =2  for no refiltering
                  iband=1;
                  fl=sr/10;
                  fh=0;
                  iphase=2;
            end    
            
            for nt=1:7
            
                a=randn(num,1);
                y=zeros(num,1);
            
                if(app.nf==1)
            %    
                  [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);
            %      
                end      
               
                if(skgoal==0)  % skew = 0
                    ex = 0.842*(ktgoal-1.36)^0.378;
            %
                    for i=1:num
                        y(i)=sign(a(i))*(abs(a(i))^ex);
                    end
                else
                    [p1,p2,skgoal]=skewness_kurtosis_coefficients(n,app.nf);       
                end
                
                if(skgoal>0)  % skew >= 0.1
                    LK=log10(ktgoal);
                    exp= p1(1)*LK^2+p1(2)*LK+p1(3);
                    exn= p2(1)*LK^2+p2(2)*LK+p2(3);
                
                    for i=1:num
                     
                        if(a(i)>0)
                            y(i)=a(i)^exp;
                        else
                            y(i)=-(abs(a(i))^exn);            
                        end    
                    end 
                end    
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                sd=std(y);
                mu=mean(y);
            
                y=y-mu;
            
                y=y*sigma/sd;
            
                [mu,sd,~,sk,kt]=kurtosis_stats(y);
                
                if( abs(sk-skgoal)<skerror && abs(kt-ktgoal)<kterror)
                    skerror=abs(sk-skgoal);
                    kterror=abs(kt-ktgoal);
                    
                    ybest=y;
                    
                    mu_best=mu;
                    sd_best=sd;  
                    sk_best=sk;
                    kt_best=kt;
                end
            
            end   

            %%%%%

            a=ybest;

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            t_string=sprintf(' mean=%4.2f  std=%g  skewness=%4.2g  kurtosis=%4.2g',mu_best,sd_best,sk_best,kt_best);
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';            

            app.SDOFResponsetoBaseInputButton.Visible='off';

        end

        % Callback function
        function RectifyListBoxValueChanged(app, event)
            clear(app); 
        end

        % Value changing function: StdDevEditField
        function StdDevEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function FrequencyHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function PhaseAngledegEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=app.OutputNameEditField.Value;
            assignin('base',name,app.THM);
            app.SDOFResponsetoBaseInputButton.Visible='on';
        end

        % Value changed function: LowpassFilteringListBox
        function LowpassFilteringListBoxValueChanged(app, event)
            ChangeFilter(app);
            clear(app);
        end

        % Callback function
        function LowpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function HigherFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: SDOFResponsetoBaseInputButton
        function SDOFResponsetoBaseInputButtonPushed(app, event)
            vibrationdata_sdof_base_Apph; 
        end

        % Value changed function: SkewnessListBox
        function SkewnessListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: KurtosisEditField
        function KurtosisEditFieldValueChanging(app, event)
            clear(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.random_skewness_kurtosis_aver11byTomIrvineLabel = uilabel(app.UIFigure);
            app.random_skewness_kurtosis_aver11byTomIrvineLabel.Text = 'random_skewness_kurtosis_a   ver 1.1  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.StdDevEditFieldLabel = uilabel(app.UIFigure);
            app.StdDevEditFieldLabel.Text = 'Std Dev ';
            app.StdDevEditField = uieditfield(app.UIFigure, 'text');
            app.StdDevEditField.ValueChangingFcn = createCallbackFcn(app, @StdDevEditFieldValueChanging, true);
            app.DurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UIFigure, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.YaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNameEditField.Value = 'random';
            app.RandomNumberSeedLabel = uilabel(app.UIFigure);
            app.RandomNumberSeedLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.UIFigure);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.LowpassFilteringListBoxLabel = uilabel(app.UIFigure);
            app.LowpassFilteringListBoxLabel.Text = 'Lowpass Filtering?';
            app.LowpassFilteringListBox = uilistbox(app.UIFigure);
            app.LowpassFilteringListBox.Items = {'yes', 'no'};
            app.LowpassFilteringListBox.ValueChangedFcn = createCallbackFcn(app, @LowpassFilteringListBoxValueChanged, true);
            app.LowpassFilteringListBox.Value = 'yes';
            app.SDOFResponsetoBaseInputButton = uibutton(app.UIFigure, 'push');
            app.SDOFResponsetoBaseInputButton.ButtonPushedFcn = createCallbackFcn(app, @SDOFResponsetoBaseInputButtonPushed, true);
            app.SDOFResponsetoBaseInputButton.Text = 'SDOF Response to Base Input';
            app.LPFilterFreqiuencyLabel = uilabel(app.UIFigure);
            app.LPFilterFreqiuencyLabel.Text = 'LP Filter Freqiuency ';
            app.SampleRate10Label = uilabel(app.UIFigure);
            app.SampleRate10Label.Text = ' = (Sample Rate)/10';
            app.KurtosisEditFieldLabel = uilabel(app.UIFigure);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.UIFigure, 'text');
            app.KurtosisEditField.ValueChangingFcn = createCallbackFcn(app, @KurtosisEditFieldValueChanging, true);
            app.SkewnessListBoxLabel = uilabel(app.UIFigure);
            app.SkewnessListBoxLabel.Text = 'Skewness';
            app.SkewnessListBox = uilistbox(app.UIFigure);
            app.SkewnessListBox.Items = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6'};
            app.SkewnessListBox.ValueChangedFcn = createCallbackFcn(app, @SkewnessListBoxValueChanged, true);
            app.SkewnessListBox.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = random_skewness_kurtosis_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end