classdef rectangular_panel_honeycomb_sandwich_pressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRectangularPanelHoneycombSandwichUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DimensionsBCQTab                matlab.ui.container.Tab
        LengthinEditFieldLabel          matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.NumericEditField
        WidthinEditFieldLabel           matlab.ui.control.Label
        WidthinEditField                matlab.ui.control.NumericEditField
        BoundaryConditionsAllEdgesLabel  matlab.ui.control.Label
        BoundaryConditionsAllEdgesListBox  matlab.ui.control.ListBox
        AmplificationFactorQEditField_2Label  matlab.ui.control.Label
        AmplificationFactorQEditField   matlab.ui.control.NumericEditField
        AddedNonStructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        AddedNonStructuralMasslbmEditField  matlab.ui.control.NumericEditField
        FaceSheetsTab                   matlab.ui.container.Tab
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.NumericEditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.NumericEditField
        FaceSheetMaterialListBoxLabel   matlab.ui.control.Label
        FaceSheetMaterialListBox        matlab.ui.control.ListBox
        IndividualFaceSheetThicknessinEditFieldLabel  matlab.ui.control.Label
        IndividualFaceSheetThicknessinEditField  matlab.ui.control.NumericEditField
        CoreTab                         matlab.ui.container.Tab
        CoreThicknessinEditFieldLabel   matlab.ui.control.Label
        CoreThicknessinEditField        matlab.ui.control.NumericEditField
        ShearModuluspsiEditFieldLabel   matlab.ui.control.Label
        ShearModuluspsiEditField        matlab.ui.control.NumericEditField
        CoreMassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        CoreMassDensitylbmin3EditField  matlab.ui.control.NumericEditField
        PressurePSDTab                  matlab.ui.container.Tab
        rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_3  matlab.ui.control.Label
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        SelectPSDResponseListBoxLabel   matlab.ui.control.Label
        SelectPSDResponseListBox        matlab.ui.control.ListBox
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        CalculatePressureResponseButton  matlab.ui.control.Button
        CalculateNaturalFrequenciesButton  matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        LoadModelButton                 matlab.ui.control.Button
        rectangular_panel_honeycomb_sandwichver11byTomIrvineLabel  matlab.ui.control.Label
        rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        E   % Face Sheet elastic modulus
        rho % Face Sheet mass density
        mu  % Face Sheet Poisson ratio
        D   % Dynamic Stiffness
        a   % Length
        b   % Width
        total_mpa  % Total mass per area     
        
        accel_psd  % Acceleration Response PSD at Panel Center
        vel_psd    % Velocity Response PSD at Panel Center
        disp_psd   % Displacement Response PSD at Panel Center
        
        accel_psd_spatial   % Acceleration Response PSD Spatial Average
        vel_psd_spatial     % Velocity Response PSD Spatial Average
        disp_psd_spatial    % Displacement Response PSD at Spatial Average        
        
        fn         % Natural Frequencies
        omegamn    % Angular Natural Frequencies
        norm       % Normalization Factor for fixed-case
        PF         % Participation Factor
        beta       % Root for Fixed BC case
        zzr        % Mass-Normalized Mode Shapes
    end
    
    methods (Access = private)
        
        function close_figures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                    
        end
        
        function change(app)
            value = strtrim(app.FaceSheetMaterialListBox.Value);
            
            app.E=0;
            app.rho=0;
            app.mu=0;
            
            if(contains(value,'Aluminum'))
                app.E=1e+07;
                app.rho=0.1;  
                app.mu=0.33;
            end
            if(contains(value,'Steel'))
                app.E=3e+07;
                app.rho= 0.28;     
                app.mu=0.3;
            end
            if(contains(value,'Copper'))
                app.E=1.6e+07;
                app.rho=  0.322;
                app.mu=0.33;
            end    
            if(contains(value,'G10'))
                app.E=2.7e+06;
                app.rho=0.065;
                app.mu=0.12;
            end
            if(contains(value,'Titanium'))
                app.E=1.6e+07;
                app.rho=0.163;
                app.mu=0.32;
            end
            if(contains(value,'Graphite'))
                app.E=1e+007;
                app.rho=0.058;
                app.mu=0.3;
            end   
            
            app.ElasticModuluspsiEditField.Value=app.E;
            app.MassDensitylbmin3EditField.Value=app.rho;
            app.PoissonRatioEditField.Value=app.mu;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);      
            app.SaveButton.Enable='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateNaturalFrequenciesButton
        function CalculateNaturalFrequenciesButtonPushed(app, event)
      
            close_figures(app);
            app.SaveButton.Enable='off';
            
            disp(' ');
            disp(' * * * * * * * * ');
            disp(' ');
            
            fig_num=1;
                       
            app.E=app.ElasticModuluspsiEditField.Value;
            if(app.E> 1.0-20)
            else
                warndlg('Enter Face Sheet Elastic Modulus');
                return;
            end
            
            app.rho=app.MassDensitylbmin3EditField.Value;
            if(app.rho> 1.0-20)
            else
                warndlg('Enter Face Sheet Mass Density');
                return;
            end            
            
            app.mu=app.PoissonRatioEditField.Value;
            if(app.mu> 1.0-20)
            else
                warndlg('Enter Face Sheet Poisson Ratio');
                return;
            end          
            
            app.a=app.LengthinEditField.Value;
            if(app.a> 1.0-20)
            else
                warndlg('Enter Length');
                return;      
            end
            
            app.b=app.WidthinEditField.Value;
            if(app.b> 1.0-20)
            else
                warndlg('Enter Width');
                return;      
            end          
    
            hc=app.CoreThicknessinEditField.Value;
            if(hc> 1.0-20)
            else
                warndlg('Enter Core Thickness');
                return;      
            end      
            
            tf=app.IndividualFaceSheetThicknessinEditField.Value;
            if(tf> 1.0-20)
            else
                warndlg('Enter Face Sheet Thickness');
                return;      
            end                  
                       
            NSM=app.AddedNonStructuralMasslbmEditField.Value;
            
            G=app.ShearModuluspsiEditField.Value;
            if(G> 1.0-20)
            else
                warndlg('Enter Core Shear Modulus');
                return;      
            end                
            
            md_core=app.CoreMassDensitylbmin3EditField.Value;
            
            if(md_core>1.0e-20)
            else
                warndlg('Enter Core Mass Density');
                return;
            end
            
            Ap=app.a*app.b;
   
            app.rho=app.rho/386;
            md_core=md_core/386;
            NSM=NSM/386;
            
            [app.D,S,~,mpa]=honeycomb_sandwich_properties(app.E,G,app.mu,tf,hc,app.rho,md_core);   
            [f1,f2]=shear_frequencies(app.E,G,app.mu,tf,hc,app.rho,md_core);
   
            M=mpa*Ap+NSM;
            app.total_mpa=M/Ap;
        
            
            fprintf('\n Total surface mass density = %8.4g lbm/ft^2 \n\n',app.total_mpa*386*144);
            
            value=strtrim(app.BoundaryConditionsAllEdgesListBox.Value);
    
            if(contains(value,'Simply'))
                [~,app.fn,app.omegamn,~,~]=ss_ss_ss_ss_plate_uniform_alt(app.D,app.a,app.b,app.total_mpa);
            else
                [app.fn,app.zzr,app.norm,app.PF,app.beta]=...
                 fixed_fixed_fixed_fixed_plate_Rayleigh_ns(app.D,app.a,app.b,app.mu,app.total_mpa,fig_num);
            end                

            tpi=2*pi;
            
            c=13500;  % speed of sound (in/sec)
            
            term=c^2*app.rho/S;
            
            num=(c^4*app.total_mpa/app.D);
            den=(1-term);
        
            aa=num/den;
        
            omega_cr=sqrt(aa);
            fcr=omega_cr/tpi;
            
            fprintf('\n Critical Frequency  fcr= %8.4g Hz \n',fcr);
            fprintf('\n Shear Transistion Frequencies \n  f1= %8.4g Hz   f2=%8.4g Hz \n',f1,f2);          
            
        end

        % Value changed function: FaceSheetMaterialListBox
        function FaceSheetMaterialListBoxValueChanged(app, event)
            change(app);
        end

        % Button pushed function: CalculatePressureResponseButton
        function CalculatePressureResponseButtonPushed(app, event)
        
            fig_num=2;
        
            FS = app.InputArrayNameEditField.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            Q=app.AmplificationFactorQEditField.Value;
            if(Q> 1.0-20)
                damp=1/(2*Q);
            else           
                warndlg('Enter Amplification Factor Q');
                return;                
            end       
            
            if(app.D>1.0e-20)
            else
                warndlg('Calculate Natural Frequencies First')
                return;
            end
            if(app.total_mpa>1.0e-20)
            else
                warndlg('Calculate Natural Frequencies First')
                return;
            end
            
            fmin=THM(1,1);
            fmax=THM(end,1);

            nf=10000;
            %
            f=zeros(nf,1);
            f(1)=fmin;
            for k=2:nf
                f(k)=f(k-1)*2^(1/48);
                if(f(k)>fmax)
                    nf=k-1;
                    break;
                end    
            end
            f=f(1:nf);
            %
            
            
            value=strtrim(app.BoundaryConditionsAllEdgesListBox.Value);
    
            if(contains(value,'Simply'))
                [app.accel_psd,app.vel_psd,app.disp_psd,app.accel_psd_spatial]=...
                    ss_ss_ss_ss_plate_uniform_pressure(app.a,app.b,app.total_mpa,damp,app.omegamn,THM,f,nf);
            else
                [app.accel_psd,app.vel_psd,app.disp_psd,app.accel_psd_spatial]=...
                    fixed_plate_uniform_pressure(app.a,app.b,app.total_mpa,damp,app.fn,app.norm,app.beta,THM,f,nf) ;               
            end
                      
            
            md=6;
            x_label='Frequency (Hz)';
            
            y_label='Accel (G^2/Hz)';
            rms_label='GRMS';
            ppp=app.accel_psd;
            
            t_string='Acceleration PSD  Center of Panel';
            
            [fig_num,~]=plot_psd_loglog_function(fig_num,x_label,y_label,rms_label,t_string,ppp,fmin,fmax,md);
            
            y_label='Accel (G^2/Hz)';
            rms_label='GRMS';
            ppp=app.accel_psd_spatial;
            fmin=THM(1,1);
            fmax=THM(end,1);
            t_string='Acceleration PSD  Spatial Average Mean';
            
            [fig_num,~]=plot_psd_loglog_function(fig_num,x_label,y_label,rms_label,t_string,ppp,fmin,fmax,md);
            
            md=6;
            y_label='Vel (ips^2/Hz)';
            rms_label='ips RMS';
            ppp=app.vel_psd;
            fmin=THM(1,1);
            fmax=THM(end,1);
            t_string='Velocity PSD  Center of Panel';
            
            [fig_num,~]=plot_psd_loglog_function(fig_num,x_label,y_label,rms_label,t_string,ppp,fmin,fmax,md);           
            
            md=6;
            y_label='Disp (in^2/Hz)';
            rms_label='inch RMS';
            ppp=app.disp_psd;
            fmin=THM(1,1);
            fmax=THM(end,1);
            t_string='Displacement PSD  Center of Panel';
            
            [~,~]=plot_psd_loglog_function(fig_num,x_label,y_label,rms_label,t_string,ppp,fmin,fmax,md);           
            
            app.SaveButton.Enable='on';
            
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            try
                mat = strtrim(app.FaceSheetMaterialListBox.Value);
                Sandwich.mat=mat;
            catch    
            end
            try
                FS = app.InputArrayNameEditField.Value;
                Sandwich.FS=FS;
            catch
            end
            try
                app.E=app.ElasticModuluspsiEditField.Value;
                Sandwich.E=app.E;
            catch
            end
            try
                app.rho=app.MassDensitylbmin3EditField.Value;
                Sandwich.rho=app.rho;
            catch
            end            
            try
                app.mu=app.PoissonRatioEditField.Value;
                Sandwich.mu=app.mu;
            catch
            end            
            try
                Q=app.AmplificationFactorQEditField.Value;
                Sandwich.Q=Q;
            catch
            end
            try
                BC=strtrim(app.BoundaryConditionsAllEdgesListBox.Value);
                Sandwich.BC=BC;
            catch
            end
            try
                app.a=app.LengthinEditField.Value;
                Sandwich.a=app.a;
            catch
            end
            try
                app.b=app.WidthinEditField.Value;
                Sandwich.b=app.b;
            catch
            end
            try
                hc=app.CoreThicknessinEditField.Value;
                Sandwich.hc=hc;
            catch
            end
            try
                tf=app.IndividualFaceSheetThicknessinEditField.Value;
                Sandwich.tf=tf;
            catch
            end
            try
                NSM=app.AddedNonStructuralMasslbmEditField.Value;
                Sandwich.NSM=NSM;
            catch
            end 
            try
                G=app.ShearModuluspsiEditField.Value;
                Sandwich.G=G;
            catch
            end
            try
                md_core=app.CoreMassDensitylbmin3EditField.Value;
                Sandwich.md_core=md_core;
            catch
            end

            % % %
 
            structnames = fieldnames(Sandwich, '-full'); % fields in the struct
  
            % % %
 
            [writefname, writepname] = uiputfile('*.mat','Save data as');
 
            %%%    writepfname = fullfile(writepname, writefname);
    
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
   
            elk=sprintf('%s%s',writepname,writefname);

            try
                save(elk, 'Sandwich'); 
            catch
                warndlg('Save error');
                return;
            end
 
            %%% SSS=load(elk)
 
            %%%%%%%%%%
            %%%%%%%%%%
            try 
                msgbox('Save Complete');
            catch
            end
    
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
        
            [filename, pathname] = uigetfile('*.mat', 'Select plot save file');

            NAME = [pathname,filename];

            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct

            k=length(structnames);

            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end

            % struct

            try
               Sandwich=evalin('base','Sandwich');
            catch
               warndlg(' evalin failed ');
               setappdata(0,'eflag',1);   
               return;
            end

            %%%%%%%%%%%%
        
            try
                Sandwich.mat=mat;               
                app.FaceSheetMaterialListBox.Value=mat;
            catch    
            end
            try
                FS=Sandwich.FS;                
                app.InputArrayNameEditField.Value=FS;
            catch
            end
            try
                app.E=Sandwich.E;                
                app.ElasticModuluspsiEditField.Value=app.E;
            catch
            end
            try
                app.rho=Sandwich.rho;                
                app.MassDensitylbmin3EditField.Value=app.rho;
            catch
            end            
            try
                app.mu=Sandwich.mu;                
                app.PoissonRatioEditField.Value=app.mu;
            catch
            end            
            try
                Q=Sandwich.Q;                
                app.AmplificationFactorQEditField.Value=Q;
            catch
            end
            try
                BC=Sandwich.BC;                
                app.BoundaryConditionsAllEdgesListBox.Value=BC;
            catch
            end
            try
                app.a=Sandwich.a;                
                app.LengthinEditField.Value=app.a;
            catch
            end
            try
                app.b=Sandwich.b;                
                app.WidthinEditField.Value=app.b;
            catch
            end
            try
                hc=Sandwich.hc;        
                app.CoreThicknessinEditField.Value=hc;
            catch
            end
            try
                tf=Sandwich.tf;
                app.IndividualFaceSheetThicknessinEditField.Value=tf;
            catch
            end
            try
                NSM=Sandwich.NSM;        
                app.AddedNonStructuralMasslbmEditField.Value=NSM;
            catch
            end 
            try
                G=Sandwich.G;                
                app.ShearModuluspsiEditField.Value=G;
            catch
            end
            try
                md_core=Sandwich.md_core;            
                app.CoreMassDensitylbmin3EditField.Value=md_core;
            catch
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            n=app.SelectPSDResponseListBox.Value;
            if(contains(n,'Acceleration Spatial'))
                data=app.accel_psd_spatial';
            end
            if(contains(n,'Acceleration Center'))
                data=app.accel_psd';
            end
            if(contains(n,'Velocity Center'))
                data=app.vel_psd';
            end
            if(contains(n,'Displacement Center'))
                data=app.disp_psd';                
            end      
            try
                data=fix_size(data);
                
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name,data);
                msgbox('Save Complete');
            catch
                warndlg('Save failed.  Check output array name.');
            end
        end

        % Value changed function: BoundaryConditionsAllEdgesListBox
        function BoundaryConditionsAllEdgesListBoxValueChanged(app, event)

            app.SaveButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRectangularPanelHoneycombSandwichUIFigure = uifigure('Visible', 'off');
            app.UIRectangularPanelHoneycombSandwichUIFigure.Name = 'UI Rectangular Panel Honeycomb Sandwich';
            app.TabGroup = uitabgroup(app.UIRectangularPanelHoneycombSandwichUIFigure);
            app.DimensionsBCQTab = uitab(app.TabGroup);
            app.DimensionsBCQTab.Title = 'Dimensions, BC & Q';
            app.LengthinEditFieldLabel = uilabel(app.DimensionsBCQTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.DimensionsBCQTab, 'numeric');
            app.WidthinEditFieldLabel = uilabel(app.DimensionsBCQTab);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField = uieditfield(app.DimensionsBCQTab, 'numeric');
            app.BoundaryConditionsAllEdgesLabel = uilabel(app.DimensionsBCQTab);
            app.BoundaryConditionsAllEdgesLabel.Text = 'Boundary Conditions  All Edges';
            app.BoundaryConditionsAllEdgesListBox = uilistbox(app.DimensionsBCQTab);
            app.BoundaryConditionsAllEdgesListBox.Items = {'Simply-Supported', 'Fixed'};
            app.BoundaryConditionsAllEdgesListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsAllEdgesListBoxValueChanged, true);
            app.BoundaryConditionsAllEdgesListBox.Value = 'Simply-Supported';
            app.AmplificationFactorQEditField_2Label = uilabel(app.DimensionsBCQTab);
            app.AmplificationFactorQEditField_2Label.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.DimensionsBCQTab, 'numeric');
            app.AddedNonStructuralMasslbmEditFieldLabel = uilabel(app.DimensionsBCQTab);
            app.AddedNonStructuralMasslbmEditFieldLabel.Text = 'Added Non-Structural Mass (lbm)';
            app.AddedNonStructuralMasslbmEditField = uieditfield(app.DimensionsBCQTab, 'numeric');
            app.FaceSheetsTab = uitab(app.TabGroup);
            app.FaceSheetsTab.Title = 'Face Sheets';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.FaceSheetsTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.FaceSheetsTab, 'numeric');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.FaceSheetsTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.FaceSheetsTab, 'numeric');
            app.PoissonRatioEditFieldLabel = uilabel(app.FaceSheetsTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.FaceSheetsTab, 'numeric');
            app.FaceSheetMaterialListBoxLabel = uilabel(app.FaceSheetsTab);
            app.FaceSheetMaterialListBoxLabel.Text = 'Face Sheet Material';
            app.FaceSheetMaterialListBox = uilistbox(app.FaceSheetsTab);
            app.FaceSheetMaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other', ''};
            app.FaceSheetMaterialListBox.ValueChangedFcn = createCallbackFcn(app, @FaceSheetMaterialListBoxValueChanged, true);
            app.FaceSheetMaterialListBox.Value = 'Aluminum';
            app.IndividualFaceSheetThicknessinEditFieldLabel = uilabel(app.FaceSheetsTab);
            app.IndividualFaceSheetThicknessinEditFieldLabel.Text = 'Individual Face Sheet Thickness (in)';
            app.IndividualFaceSheetThicknessinEditField = uieditfield(app.FaceSheetsTab, 'numeric');
            app.CoreTab = uitab(app.TabGroup);
            app.CoreTab.Title = 'Core';
            app.CoreThicknessinEditFieldLabel = uilabel(app.CoreTab);
            app.CoreThicknessinEditFieldLabel.Text = 'Core Thickness (in)';
            app.CoreThicknessinEditField = uieditfield(app.CoreTab, 'numeric');
            app.ShearModuluspsiEditFieldLabel = uilabel(app.CoreTab);
            app.ShearModuluspsiEditFieldLabel.Text = 'Shear Modulus (psi)';
            app.ShearModuluspsiEditField = uieditfield(app.CoreTab, 'numeric');
            app.CoreMassDensitylbmin3EditFieldLabel = uilabel(app.CoreTab);
            app.CoreMassDensitylbmin3EditFieldLabel.Text = 'Core Mass Density (lbm/in^3)';
            app.CoreMassDensitylbmin3EditField = uieditfield(app.CoreTab, 'numeric');
            app.PressurePSDTab = uitab(app.TabGroup);
            app.PressurePSDTab.Title = 'Pressure PSD';
            app.rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_3 = uilabel(app.PressurePSDTab);
            app.rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_3.Text = {'The input array must have two columns:  freq (Hz) & pressure (psi^2/Hz) '; ''};
            app.InputArrayNameEditFieldLabel = uilabel(app.PressurePSDTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.PressurePSDTab, 'text');
            app.SelectPSDResponseListBoxLabel = uilabel(app.PressurePSDTab);
            app.SelectPSDResponseListBoxLabel.Text = 'Select PSD Response';
            app.SelectPSDResponseListBox = uilistbox(app.PressurePSDTab);
            app.SelectPSDResponseListBox.Items = {'Acceleration Spatial Average', 'Acceleration Center', 'Velocity Center', 'Displacement Center'};
            app.SelectPSDResponseListBox.Value = 'Acceleration Spatial Average';
            app.OutputArrayNameEditFieldLabel = uilabel(app.PressurePSDTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.PressurePSDTab, 'text');
            app.SaveButton = uibutton(app.PressurePSDTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CalculatePressureResponseButton = uibutton(app.PressurePSDTab, 'push');
            app.CalculatePressureResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePressureResponseButtonPushed, true);
            app.CalculatePressureResponseButton.Text = 'Calculate Pressure Response';
            app.CalculateNaturalFrequenciesButton = uibutton(app.UIRectangularPanelHoneycombSandwichUIFigure, 'push');
            app.CalculateNaturalFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesButtonPushed, true);
            app.CalculateNaturalFrequenciesButton.Text = 'Calculate Natural Frequencies';
            app.ReturnButton = uibutton(app.UIRectangularPanelHoneycombSandwichUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SaveModelButton = uibutton(app.UIRectangularPanelHoneycombSandwichUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.UIRectangularPanelHoneycombSandwichUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.rectangular_panel_honeycomb_sandwichver11byTomIrvineLabel = uilabel(app.UIRectangularPanelHoneycombSandwichUIFigure);
            app.rectangular_panel_honeycomb_sandwichver11byTomIrvineLabel.Text = 'rectangular_panel_honeycomb_sandwich   ver 1.1  by Tom Irvine';
            app.rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_2 = uilabel(app.UIRectangularPanelHoneycombSandwichUIFigure);
            app.rectangular_panel_honeycomb_sandwichver10byTomIrvineLabel_2.Text = {'This script calculates the response of a rectangular honeycomb sandwich panel to a uniform pressure field.'; ''};
            % Show the figure after all components are created
            app.UIRectangularPanelHoneycombSandwichUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rectangular_panel_honeycomb_sandwich_pressure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRectangularPanelHoneycombSandwichUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRectangularPanelHoneycombSandwichUIFigure)
        end
    end
end