classdef rectangular_plate_bending_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RectangularPlateBendingUIFigure  matlab.ui.Figure
        rectangular_plate_bending_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MaterialPropertiesTab           matlab.ui.container.Tab
        UniformNonstructuralMasslbmEditField  matlab.ui.control.EditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialLabel                   matlab.ui.control.Label
        CommonBCsTab                    matlab.ui.container.Tab
        BottomLengthinEditField         matlab.ui.control.EditField
        BottomLengthinEditFieldLabel    matlab.ui.control.Label
        SideWidthinEditField            matlab.ui.control.EditField
        SideWidthinEditFieldLabel       matlab.ui.control.Label
        ThicknessinEditField            matlab.ui.control.EditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        CalculatefnButton               matlab.ui.control.Button
        BottomBCListBox                 matlab.ui.control.ListBox
        BottomBCListBoxLabel            matlab.ui.control.Label
        TopBCListBox                    matlab.ui.control.ListBox
        TopBCListBoxLabel               matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FixedPointatEachCornerTab       matlab.ui.container.Tab
        ResultsTextArea_fixed_point     matlab.ui.control.TextArea
        ResultsTextArea_2Label          matlab.ui.control.Label
        BottomLengthinEditField_fixed_point  matlab.ui.control.EditField
        BottomLengthinEditField_Label_fixed_point  matlab.ui.control.Label
        SideWidthinEditField_fixed_point  matlab.ui.control.EditField
        SideWidthinEditField_Label_fixed_point  matlab.ui.control.Label
        ThicknessinEditField_fixed_point  matlab.ui.control.EditField
        ThicknessinEditField_Label_fixed_point  matlab.ui.control.Label
        CalculatefnButton_fixed_point   matlab.ui.control.Button
        OtherBCCasesTab                 matlab.ui.container.Tab
        BCsListBox                      matlab.ui.control.ListBox
        BCsListBoxLabel                 matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        NaturalFrequencyTab             matlab.ui.container.Tab
        CalculatefnButton_SS            matlab.ui.control.Button
        BottomLengthinEditField_SS      matlab.ui.control.EditField
        BottomLengthinEditField_SSLabel  matlab.ui.control.Label
        SideWidthinEditField_SS         matlab.ui.control.EditField
        SideWidthinEditField_SSLabel    matlab.ui.control.Label
        ThicknessinEditField_SS         matlab.ui.control.EditField
        ThicknessinEditField_SSLabel    matlab.ui.control.Label
        UITable_fn                      matlab.ui.control.Table
        DampingTab                      matlab.ui.container.Tab
        UniformQEditField               matlab.ui.control.EditField
        UniformQEditFieldLabel          matlab.ui.control.Label
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AppliedForceTab                 matlab.ui.container.Tab
        NumberofModesIncludedListBox    matlab.ui.control.ListBox
        NumberofModesIncludedListBoxLabel  matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        PointForceFRFTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CalculateResponseTab            matlab.ui.container.Tab
        ResponseLocationListBox         matlab.ui.control.ListBox
        ResponseLocationListBoxLabel    matlab.ui.control.Label
        AlongWidthEditField             matlab.ui.control.EditField
        AlongWidthEditFieldLabel        matlab.ui.control.Label
        AlongLengthEditField            matlab.ui.control.EditField
        AlongLengthEditFieldLabel       matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        FrequencyHzLimitsLabel          matlab.ui.control.Label
        ForceInputLocationLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        ResultsTextArea_point_force_FRF  matlab.ui.control.TextArea
        ResultsTextArea_point_force_FRF_Label  matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        FRFTypeListBox                  matlab.ui.control.ListBox
        FRFTypeListBoxLabel             matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ForceTransientTab               matlab.ui.container.Tab
        TabGroup5_2                     matlab.ui.container.TabGroup
        ReadArrayTab                    matlab.ui.container.Tab
        UpdateListButton_2              matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        ForceLabel                      matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        PointForceResponseTab           matlab.ui.container.Tab
        AlongWidthEditField_2           matlab.ui.control.EditField
        AlongWidthEditField_2Label      matlab.ui.control.Label
        AlongLengthEditField_2          matlab.ui.control.EditField
        AlongLengthEditField_2Label     matlab.ui.control.Label
        ResponseLocationListBox_pft     matlab.ui.control.ListBox
        ResponseLocationListBox_Label_pft  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ForceInputLocationLabel_2       matlab.ui.control.Label
        CalculateButton_pft             matlab.ui.control.Button
        UniformPressureResponseTab      matlab.ui.container.Tab
        CalculateButton_pressure        matlab.ui.control.Button
        ResponseLocationListBox_pressure  matlab.ui.control.ListBox
        ResponseLocationListBox_Label_pft_2  matlab.ui.control.Label
        ResultsTab_pft                  matlab.ui.container.Tab
        ResultsTextArea_pft             matlab.ui.control.TextArea
        ResultsTextArea_Label_pft       matlab.ui.control.Label
        SaveTab_pft                     matlab.ui.container.Tab
        OutputArrayNameEditField_pft    matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        SaveButton_pft                  matlab.ui.control.Button
        TimeHistoryListBox              matlab.ui.control.ListBox
        TimeHistoryListBoxLabel         matlab.ui.control.Label
        BaseExcitationTab               matlab.ui.container.Tab
        SettoCenterButton               matlab.ui.control.Button
        yinEditField                    matlab.ui.control.EditField
        yinEditFieldLabel               matlab.ui.control.Label
        xinEditField                    matlab.ui.control.EditField
        xinEditFieldLabel               matlab.ui.control.Label
        ResponseLocationReferencedtoLowerLeftCornerLabel  matlab.ui.control.Label
        NumberofModesIncludedListBox_baseTrans  matlab.ui.control.ListBox
        NumberofModesIncludedListBox_Label_baseTrans  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        TransmissibilityTab             matlab.ui.container.Tab
        TabGroup5_3                     matlab.ui.container.TabGroup
        CalculateResponseTab_2          matlab.ui.container.Tab
        MaxEditField_baseTrans          matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_baseTrans          matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        FrequencyHzLimitsLabel_2        matlab.ui.control.Label
        CalculateButton_baseTrans       matlab.ui.control.Button
        ResultsTab_2                    matlab.ui.container.Tab
        ResultsTextArea_FRF_baseTrans   matlab.ui.control.TextArea
        ResultsTextArea_FRF_Label_baseTrans  matlab.ui.control.Label
        SaveTab_baseTrans               matlab.ui.container.Tab
        OutputArrayNameEditField_baseTrans  matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel_baseTrans  matlab.ui.control.Label
        SaveButton_baseTrans            matlab.ui.control.Button
        FRFTypeListBox_baseTrans        matlab.ui.control.ListBox
        FRFTypeListBox_Label_baseTrans  matlab.ui.control.Label
        SineInputTab                    matlab.ui.container.Tab
        SettoFundamentalFrequencyButton  matlab.ui.control.Button
        AccelGEditField                 matlab.ui.control.EditField
        AccelGEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        ResultsTextArea_baseTrans       matlab.ui.control.TextArea
        ResultsTextArea_Label_baseTrans  matlab.ui.control.Label
        CalculateButton_baseTrans_2     matlab.ui.control.Button
        PSDInputTab                     matlab.ui.container.Tab
        TabGroup5_4                     matlab.ui.container.TabGroup
        CalculateResponseTab_3          matlab.ui.container.Tab
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_3              matlab.ui.control.Button
        text1_2                         matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculateButton_10              matlab.ui.control.Button
        ResultsTab_3                    matlab.ui.container.Tab
        ResultsTextArea_base_accel_psd  matlab.ui.control.TextArea
        ResultsTextArea_base_accel_Label_psd  matlab.ui.control.Label
        SaveTab_3                       matlab.ui.container.Tab
        OutputArrayNameEditField_psd    matlab.ui.control.EditField
        OutputArrayNameEditField_3Label  matlab.ui.control.Label
        SaveButton_psd                  matlab.ui.control.Button
        PSDTypeListBox                  matlab.ui.control.ListBox
        PSDTypeListBoxLabel             matlab.ui.control.Label
        ArbitraryInputTab               matlab.ui.container.Tab
        TabGroup5_5                     matlab.ui.container.TabGroup
        ReadArrayTab_2                  matlab.ui.container.Tab
        CalculateButton_arb_base        matlab.ui.control.Button
        SelectInputArrayListBox_arb     matlab.ui.control.ListBox
        SelectInputArrayListBox_Label_arb  matlab.ui.control.Label
        UpdateListButton_arb            matlab.ui.control.Button
        ForceLabel_arb                  matlab.ui.control.Label
        text1_arb                       matlab.ui.control.Label
        listbox_method_arb              matlab.ui.control.ListBox
        ResultsTab_4                    matlab.ui.container.Tab
        ResultsTextArea_arbit           matlab.ui.control.TextArea
        ResultsTextArea_arbit_Label     matlab.ui.control.Label
        SaveTab_arb                     matlab.ui.container.Tab
        OutputArrayNameEditField_arb    matlab.ui.control.EditField
        OutputArrayNameEditField_4Label  matlab.ui.control.Label
        SaveButton_arb                  matlab.ui.control.Button
        ResponseTypeListBox             matlab.ui.control.ListBox
        ResponseTypeListBoxLabel        matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        imat   % Description
        iu     %
        E      %
        rho    %
        thick  %
        D      %
        sqDrho %
        L      %  
        W      %
        mu     %
        NSM    %
        total_mass %
        area   %
        rhos   %
        dm     %
        fn     %
        kflag  %
        fbig   %
        fbigs  % 
        method %
        receptance  %
        mobility    %
        accelerance % 
        omegamn     %
        acceleration      %
        relative_velocity %
        relative_displacement %
        bending_stress        %
        bending_stress_tensor %   
        THM   %
        Amn   %
        rel_disp_H  %
        rel_vel_H   %
        accel_H     %
        H_vM        %
        acc_response_psd  %
        rv_response_psd   %
        rd_response_psd   %
        vM_response_psd   %    
        nBC   %
        DD    %
        beta  %
        alpha_r  %
        beta_r   %
        gamma_r  %
        theta_r  % 
        ZAA      %    
        part_corners  %
        part_r   %
        root     %
        jflag    %
    end
    
    methods (Access = private)

        function corners(app)

            rng(1);
            
            fmin=1.0e+90;    

            Property(app);
            
            app.D=app.E*app.thick^3/(12*(1-app.mu^2));
            app.sqDrho=sqrt(app.D/app.rhos);

            app.L=str2num(app.BottomLengthinEditField_fixed_point.Value);
            app.W=str2num(app.SideWidthinEditField_fixed_point.Value);
            app.thick=str2num(app.ThicknessinEditField_fixed_point.Value);

            if(app.iu==2)
                app.thick=app.thick/1000;    
            end

            vol=app.L*app.W*app.thick;
    
            app.total_mass=app.rho*vol + app.NSM;
                        
            app.area=app.L*app.W;
            app.rhos=app.total_mass/app.area;

            a=app.L;
            b=app.W;
            h=app.thick;
           
            % sq_mass=sqrt(app.total_mass);
            fprintf('vol=%8.4g  total_mass=%8.4g  area=%8.4g app.D=%8.4g\n',vol,app.total_mass,app.area,app.D)
            fprintf('a=%8.4g  h=%8.4g  app.rhos*h=%8.4g \n',a,h,app.rhos*h)
            
            % 
            num=40;
            np1=num+1;
            
            nnn=num;
            dx=a/num; 
            dy=b/num; 
            pia=pi/a; 
            pib=pi/b; 
            % 
            knum=2000; 
            app.alpha_r=0; 
            app.beta_r=0;
            app.gamma_r=0;
            %
            fn1=1.13*sqrt(app.D/app.rhos)/a^2;
            fn2=1.13*sqrt(app.D/app.rhos)/b^2;
            fff=[fn1 fn2];
            flow=min(fff);
            fup=max(fff);
            %
            c1=2*app.mu;
            c2=2*(1-app.mu);
            %
            for k=1:knum
                alpha= 0.8*rand+0.2;
                app.beta = 0.8*rand+0.2;
                
                if(k==1)
                    app.beta=alpha;
                end
            %
                if(k>10 && rand > 0.8 )
                    alpha=app.alpha_r*(0.96+0.08*rand);
                    app.beta =app.beta_r*(0.96+0.08*rand);    
                end
            %
                aaa=[alpha app.beta];
                gamma= min(aaa)*rand;
            %    
                if(abs(a-b)<a/100)
                    alpha=app.beta;
                end
            %
                sum=alpha+app.beta+gamma;
                alpha=alpha/sum;
                app.beta =app.beta/sum;
                gamma=gamma/sum;
            %
                pia_alpha=pia*alpha;
                pib_beta =pib*app.beta;
                pia2_alpha=(pia^2)*alpha;
                pib2_beta =(pib^2)*app.beta;    
            %
                Z1A=0;
                Z2A=0;
                V=0;
                T=0;
                for i=0:nnn
                    x=(i*dx-(a/2))*pia;
                    cx=cos(x);
                    sx=sin(x);
                    for j=0:nnn
                        y=(j*dy-(b/2))*pib;
                        sy=sin(y);
                        cy=cos(y);
                        Z=alpha*cx + app.beta*cy + gamma*cx*cy;
            %
                        Z1A=Z1A+Z;            
                        Z2A=Z2A+Z^2;
            %
                        dZdx  =  -pia_alpha*(1+gamma*sy)*sx;
                        d2Zdx2=  -pia2_alpha*(1+gamma*sy)*cx;   
            %
                        dZdy  =  -pib_beta*(1+gamma*sx)*sy;
                        d2Zdy2=  -pib2_beta*(1+gamma*sx)*cy;  
            %    
                        d2Zdxdy=pia_alpha*pib_beta*gamma*sy*sx;
            %
                        V1=(d2Zdx2)^2;
                        V2=(d2Zdy2)^2;
                        V3=c1*(d2Zdx2)*(d2Zdy2);
                        V4=c2*(d2Zdxdy)^2;
            %            
                        V=V+ V1+V2+V3+V4;
                        T=T+Z^2;
                    end
                end
                V=V*app.D/2;
                T=T*app.rhos/2;
                omega=sqrt(V/T);
                app.fn=omega/(2*pi);
                if(app.fn<fmin && app.fn>= flow)
                    fmin=app.fn;
                    app.alpha_r=alpha;
                    app.beta_r =app.beta;
                    app.gamma_r=gamma;
            %
                    out1=sprintf('\n alpha=%8.4g  beta=%8.4g \n gamma=%8.4g ',app.alpha_r,app.beta_r,app.gamma_r);
            %
                    Z1A_r=Z1A;
                    Z2A_r=Z2A;
            %
                end  
            %
            end
            %
                qq=dx*dy*h*app.rhos;
            
                     ZA=Z1A_r*qq;
                    app.ZAA=Z2A_r*qq;
            %
                   app.ZAA=sqrt(app.ZAA);
                   
                app.part_r=(ZA/app.ZAA);  
                app.part_corners=app.part_r;
            %
            sflow=sprintf('Lower bound f= %8.4g Hz \n',flow);
              sup=sprintf('Upper bound f= %8.4g Hz \n\n',fup);
            
            sest=sprintf('Estimated fn=%8.4g Hz',fmin);
            
            s1=sprintf('%s \n\n %s %s %s',out1,sflow,sup,sest);
            app.ResultsTextArea_fixed_point.Value=s1;
            
            %
            clear xx;
            clear yy;
            clear zzr;
            %
            
            fig_num=1;
            figure(fig_num);  
            fig_num=fig_num+1;
            setappdata(0,'fig_num',fig_num);
            
            xx=zeros(np1,1);
            yy=zeros(np1,1);
            zzr=zeros(np1,np1);
            %
            for i=1:np1 
                    xx(i)=((i-1)*dx-(a/2));
                    cx=cos(xx(i)*pia);
            %        sx=sin(xx(i)*pia);
                    for j=1:np1
                        yy(j)=((j-1)*dy-(b/2));
            %            sy=sin(yy(j));
                        cy=cos(yy(j)*pib);
                        zzr(i,j)=app.alpha_r*cx + app.beta_r*cy + app.gamma_r*cx*cy;
                    end
            end
            
            zzr=zzr/(max(max(abs(zzr))));
            
            xxmin=min(xx);
            yymin=min(yy);
            
            xx=xx-xxmin;
            yy=yy-yymin;
            
            clear aaa;
            clear abc;
            abc=[max(xx) max(yy)];
            aaa=max(abc);
            xmin=-aaa;
            ymin=-aaa;
            zmin=0;
            zmax=3;
            xmax=aaa;
            ymax=aaa;
            surf(xx,yy,zzr);
            % xlim([xmin xmax]) 
            % ylim([ymin ymax]) 
            zlim([zmin zmax]) 
            xlabel('X');
            ylabel('Y');
            
            out1=sprintf('fn=%8.4g Hz',fmin);
            title(out1);
            app.fn(1)=fmin;
          
        end    

        function fixed_free_fixed_free(app)
            rng(1);
            fmin=1.0e+90;
            num=200; 
            nnn=num;
            a=app.L;
            b=app.W;
            dx=b/num;   % needed switch
            dy=a/num; 
            % 
            knum=800; 
            app.alpha_r=0; 
            app.theta_r=0;
            %
            fn1=(22.373/(2*pi))*sqrt(app.D/(app.rho*app.thick))/b^2;
            %
            fprintf('\n Expected:  fn ~ %8.4g Hz \n',fn1);
            %
            disp(' ');
            disp(' Calculating natural frequency.... ');
            %
            LL=b;
            %
            c1=2*app.mu;
            c2=2*(1-app.mu);
            %
            app.root=4.73004;
            bL=app.root;
            app.beta=app.root/LL;
            beta2=app.beta^2;
            C=(sinh(bL)+sin(bL))/(cosh(bL)-cos(bL));  
            %
            x=zeros(nnn+1,1);
            y=zeros(nnn+1,1);
            for i=1:(nnn+1)
               ii=i-1;
               x(i)=ii*dx;
               y(i)=(ii*dy-(a/2));
            end 
            %    
            disp(' ');
            
            progressbar;
            
            for k=1:knum
                
                progressbar(k/knum);
                
                if(k<knum/10 || rand<0.5)
                    alpha=  -1.0 +2*rand;
                    theta=   5*rand/a;
                else
                    alpha=app.alpha_r*(0.98+0.04*rand);
                    theta=app.theta_r*(0.98+0.04*rand);    
                end
                theta2=theta^2;
            %
                if(k==1)
                    alpha=0;
                end
            %
                V=0;
                T=0;   
            %    
                argx=app.beta*x;
                argy=theta*y;
            %
                Z1A=0;
                Z2A=0;   
            %
                for i=1:nnn+1
                    cosh_arg=cosh(argx(i));
                    cos_arg=cos(argx(i));
                    sinh_arg=sinh(argx(i));
                    sin_arg=sin(argx(i));
            %        
                                P=(cosh_arg-cos_arg)-C*(sinh_arg-sin_arg);     
                        dPdx=app.beta*((sinh_arg+sin_arg)-C*(cosh_arg-cos_arg));
                     d2Pdx2=beta2*((cosh_arg+cos_arg)-C*(sinh_arg+sin_arg));
            %        
                    for j=1:nnn+1
                        cos_arg=cos(argy(j));
                        sin_arg=sin(argy(j));
                             WW=1+alpha*cos_arg;
                          dWdy=-alpha*theta*sin_arg;
                        d2Wdy2=-alpha*theta2*cos_arg;            
            %
                        Z=P*WW;
                        
                        Z1A=Z1A+Z;            
                        Z2A=Z2A+Z^2;         
            %    
                        dZdx  =   dPdx*WW; 
                        d2Zdx2= d2Pdx2*WW;     
            %
                        dZdy  = P*dWdy; 
                        d2Zdy2= P*d2Wdy2;  
            %    
                        d2Zdxdy=dPdx*dWdy;
            %
                        V1=(d2Zdx2)^2;
                        V2=(d2Zdy2)^2;
                        V3=c1*(d2Zdx2)*(d2Zdy2);
                        V4=c2*(d2Zdxdy)^2;
            %            
                        V=V+ V1+V2+V3+V4;
                        T=T+Z^2;
                    end
                end
                V=V*app.D/2;
                T=T*app.rho*app.thick/2;
                omega=sqrt(V/T);
                app.fn=omega/(2*pi);
                if(app.fn<fmin)
                    fmin=app.fn;
                    app.alpha_r=alpha;
                    app.theta_r =theta;
                    
                    Z1A_r=Z1A;
                    Z2A_r=Z2A;        
                    
                    fprintf('%d %8.4g Hz  alpha=%8.4g  theta=%8.4g %8.4g \n',k,app.fn,app.alpha_r,app.theta_r);
                 
                end
            end
            %
            pause(0.3);
            progressbar(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            h=app.thick;
            qq=dx*dy*h*app.rho;
            
                     ZA=Z1A_r*qq;
                    app.ZAA=Z2A_r*qq;
            %
                   app.ZAA=sqrt(app.ZAA);
                   
                app.part_r=(ZA/app.ZAA);
            %            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %
            clear xx;
            clear yy;
            clear zzr;
            %
            fig_num=1;
            figure(fig_num);
            fig_num=fig_num+1;
            zmax=0;
            zmin=0;
            %
            num=40; 
            
            dx=b/num;   % needed switch
            dy=a/num; 
            %
            clear arg;
            clear x;
            clear y;
            %
            for i=1:(num+1)
                      x=((i-1)*dx-(b/2));
                      yy(i)=x;
                      arg=app.beta*(x+b/2);
                      P=(cosh(arg)-cos(arg))-C*(sinh(arg)-sin(arg)); 
            %          
                    for j=1:(num+1)
                        y=((j-1)*dy-(a/2));
                        xx(j)=y;
                        arg=app.theta_r*y;
                          WW=1+app.alpha_r*cos(arg);
            %
                         zzr(i,j)=P*WW;
            %
                        if(zzr(i,j)>zmax)
                            zmax=zzr(i,j);
                        end
            %
                        if(zzr(i,j)<zmin)
                            zmin=zzr(i,j);
                        end            
                    end
            end
            %
            %
            zzr=zzr/(max(max(abs(zzr))));
            
            minxx=min(xx);
            minyy=min(yy);
            
            xx=xx-min(xx);
            yy=yy-min(yy);
            
            clear aaa;
            clear abc;
            xymax=max([max(xx) max(yy)]);
            
            zmin=0;
            zmax=2.5;
            
            surf(xx,yy,zzr);
            % xlim([0 xymax]) 
            % ylim([0 xymax]) 
            zlim([zmin zmax])
            xlabel('X');
            ylabel('Y');
            out1=sprintf('Rayleigh: fn=%8.4g Hz',fmin); 
            title(out1);
            
            %
            disp(' ');
            out1=sprintf(' Expected:  fn ~ %8.4g Hz   Rayleigh: fn=%8.4g Hz',fn1,fmin);
            disp(out1);
            %
            
            se=sprintf('Expected: fn ~ %8.4g Hz \n\n',fn1);
            sr=sprintf('Rayleigh: fn = %8.4g Hz',fmin);
            s1=sprintf('%s %s',se,sr);
            
        end    

        function Fixed_all(app)
            fig_num=1;
            psave=2;
            [app.fn,zzr,fig_num,P,dPdx,d2Pdx2,W,dWdy,d2Wdy2,app.ZAA,PF,app.beta_r]=...
            fixed_fixed_fixed_fixed_plate_RayleighG_alt(app.D,app.rho,app.thick,app.L,app.W,app.mu,app.rhos,fig_num,psave);
            app.part_r=PF;
        end    

        function SS(app)

            nmodes=40;

            app.omegamn=zeros(nmodes,nmodes);
            faux=zeros(nmodes,nmodes);
  
            n2=nmodes*nmodes;
            i=1;
            for m=1:nmodes
                for n=1:nmodes
                    app.omegamn(m,n)=app.DD*( (m*pi/app.L)^2 + (n*pi/app.W)^2 );
                    faux(i)=app.omegamn(m,n)/(2*pi);
                    i=i+1;        
                end
            end
            %
            
            sort(faux);

            a=app.L;
            b=app.W;
            
            app.Amn=2/sqrt(app.total_mass);
            %
            AAA=(2*sqrt(app.total_mass)/pi^2);
            FFF= 2*a*b/(sqrt(app.total_mass)*pi^2);
            %
            iv=1;
            %
            part=zeros(nmodes,nmodes);
            %
            app.fbig=zeros(n2,8);
            m_index=zeros(n2,1);
            n_index=zeros(n2,1);
            %
            for i=1:nmodes
                for j=1:nmodes
                    core=(cos(i*pi)-1)*(cos(j*pi)-1)/(i*j);
                    part(i,j)=AAA*core;
                    
                    force_part=FFF*core;
                    
                        m_index(iv)=i;
                        n_index(iv)=j;        
                    
                        app.fbig(iv,1)=faux(iv);
                        app.fbig(iv,2)=i;
                        app.fbig(iv,3)=j;
                        app.fbig(iv,4)=part(i,j);
                        app.fbig(iv,5)=(part(i,j))^2;
                        app.fbig(iv,6)=m_index(iv);
                        app.fbig(iv,7)=n_index(iv);
                        app.fbig(iv,8)=force_part;
                    
                        iv=iv+1;        
                end
            end
            
            app.fbig=sortrows(app.fbig,1);
        end

        function update_arb(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox_arb.Items=unique(sort(cell_array)); 
            app.listbox_method_arb.Value=app.listbox_method.Items{1};            
        end   

       function update_psd(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            catch
            end
        end

        function update(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end   

        function preprocess(app)

            ClearResults(app);
            
            app.kflag=1;

            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                app.kflag=999;
                return;
            end
            if(isempty(app.dm))
                warndlg('Enter Damping first');
                app.kflag=999;
                return;
            end
            if(app.jflag==1)
                warndlg('Recalculate Natural Frequencies first')
                app.kflag=999;
            end    

        end    

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            app.UniformQEditField.Visible='off';
            app.UniformQEditFieldLabel.Visible='off';
            app.UITable.Visible='off';

            if(contains(nD,'Uniform'))

                app.UniformQEditField.Visible='on';
                app.UniformQEditFieldLabel.Visible='on';

                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UniformQEditFieldLabel.Text='Uniform Q';
                    app.UniformQEditField.Value='10';
                else  
                    app.UniformQEditFieldLabel.Text='Uniform Damping Ratio';                   
                    app.UniformQEditField.Value='0.05';               
                end   
            else
                app.UITable.Visible='on';

                app.UITable.RowName={'1'; '2'; '3';'4';'5';'6';...
                                     '7';'8';'9';'10';'11';'12'};

                try
                    for i=1:12
                        data_s{i,1}=sprintf('%8.4g',app.fn(i));
                        if(contains(nmn,'Amplification'))
                            cn={'fn (Hz)','Q'};
                            data_s{i,2}='10';
                        else
                            cn={'fn (Hz)','ratio'};
                            data_s{i,2}='0.05';                           
                        end    
                    end    
                    app.UITable.ColumnName=cn;
                    app.UITable.Data=data_s;
                    s = uistyle('HorizontalAlignment','center'); % create a style
                    addStyle(app.UITable,s) % adjust the style                  
                catch
                end
            end

        end

        function geometry_mass(app)

            Property(app);

            if(app.iu==2)
                app.thick=app.thick/1000;
            end
            
            vol=app.L*app.W*app.thick;
    
            app.total_mass=app.rho*vol + app.NSM;
                        
            app.area=app.L*app.W;
            app.rhos=app.total_mass/app.area;

            
            app.D=app.E*app.thick^3/(12*(1-app.mu^2));
            app.sqDrho=sqrt(app.D/app.rhos);
        end     

        function Property(app)
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);
            app.mu=str2num(app.PoissonRatioEditField.Value);
            app.NSM=str2num(app.UniformNonstructuralMasslbmEditField.Value);

            if(app.iu==1)
                app.rho=app.rho/386;
                app.NSM=app.NSM/386;
            else
                app.E=app.E*1e+09;
            end

        end     

        function Unit(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);    

            Items = char(app.BCsListBox.Items);
            im=app.BCsListBox.Value;
            [app.nBC]=find_index(Items,im);

            if(app.nBC==1)
                app.NumberofModesIncludedListBox_baseTrans.Enable='on';
            else
                app.NumberofModesIncludedListBox_baseTrans.Value='1';
                app.NumberofModesIncludedListBox_baseTrans.Enable='off';
            end

            if(app.iu==1)

                app.BottomLengthinEditFieldLabel.Text='Bottom Length (in)';
                app.SideWidthinEditFieldLabel.Text='Side Width (in)'; 
                app.BottomLengthinEditField_Label_fixed_point.Text='Bottom Length (in)';
                app.SideWidthinEditField_Label_fixed_point.Text='Side Width (in)'; 
                app.ThicknessinEditField_Label_fixed_point.Text='Thickness (in)';
 
                if(app.nBC~=3)
                    app.yinEditFieldLabel.Text='y (in)';
                    app.xinEditFieldLabel.Text='x (in)';            
                    app.BottomLengthinEditField_SSLabel.Text='Bottom Length (in)';               
                    app.SideWidthinEditField_SSLabel.Text='Side Width (in)';
                else
                    app.yinEditFieldLabel.Text='Free y (in)';
                    app.xinEditFieldLabel.Text='Fixed x (in)';         
                    app.BottomLengthinEditField_SSLabel.Text='Fixed Edge Length (in)';               
                    app.SideWidthinEditField_SSLabel.Text='Free Edge Length (in)';                   
                end
                app.ThicknessinEditField_SSLabel.Text='Thickness (in)';           
                app.ThicknessinEditFieldLabel.Text='Thickness (in)';   
                sf=sprintf('The input array must have two columns: \n time(sec) & force(lbf) or pressure(psi)');
            else               
                app.BottomLengthinEditFieldLabel.Text='Bottom Length (m)';
                app.SideWidthinEditFieldLabel.Text='Side Width (m)'; 

                app.BottomLengthinEditField_Label_fixed_point.Text='Bottom Length (m)';
                app.SideWidthinEditField_Label_fixed_point.Text='Side Width (m)'; 
                app.ThicknessinEditField_Label_fixed_point.Text='Thickness (mm)';

                if(app.nBC~=3)
                    app.yinEditFieldLabel.Text='y (m)';
                    app.xinEditFieldLabel.Text='x (m)'; 
                    app.BottomLengthinEditField_SSLabel.Text='Bottom Length (m)';               
                    app.SideWidthinEditField_SSLabel.Text='Side Width (m)';
                else
                    app.yinEditFieldLabel.Text='Free y (m)';
                    app.xinEditFieldLabel.Text='Fixed x (m)'; 
                    app.BottomLengthinEditField_SSLabel.Text='Fixed Edge Length (m)';               
                    app.SideWidthinEditField_SSLabel.Text='Free Edge Length (m)';                   
                end
                app.BottomLengthinEditField_SSLabel.Text='Bottom Length (m)';
                app.SideWidthinEditField_SSLabel.Text='Side Width (m)';                                 
                app.ThicknessinEditField_SSLabel.Text='Thickness (mm)';              
                app.ThicknessinEditFieldLabel.Text='Thickness (mm)';   
                sf=sprintf('The input array must have two columns: \n time(sec) & force(N) or pressure(Pa)');               
            end  

            app.ForceLabel.Text=sf;

        end

        function ChangeMaterials(app)

            Unit(app); 

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,poisson]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';   
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)';   
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';              
            end    

            app.PoissonRatioEditField.Value=sprintf('%g',poisson);
           
        end

        
        function ClosePlots(app)       

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        function ClearResults(app)
            ClosePlots(app); 
            app.ResultsTextArea_fixed_point.Value='';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';                     
            app.UITable_fn.Visible='off';       
            app.ResultsTextArea_point_force_FRF.Visible='off';
            app.ResultsTextArea_point_force_FRF_Label.Visible='off';
            app.ResultsTextArea_pft.Visible='off';
            app.ResultsTextArea_Label_pft.Visible='off'; 
            app.ResultsTextArea_FRF_baseTrans.Visible='off';
            app.ResultsTextArea_FRF_Label_baseTrans.Visible='off';
            app.ResultsTextArea_Label_baseTrans.Visible='off';
            app.ResultsTextArea_baseTrans.Visible='off';
            app.ResultsTextArea_base_accel_psd.Visible='off';
            app.ResultsTextArea_base_accel_Label_psd.Visible='off';
            app.ResultsTextArea_arbit.Visible='off';
            app.ResultsTextArea_arbit_Label.Visible='off';           
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClearResults(app);
            ChangeMaterials(app);
            ChangeDamping(app);
            update(app);
            update_psd(app);
            update_arb(app);
            app.jflag=1;
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq; 
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)

            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end            
        end

        % Button pushed function: CalculatefnButton
        function CalculatefnButtonPushed(app, event)
            ClearResults(app);

            Items = char(app.LeftBCListBox.Items);
            im=app.LeftBCListBox.Value;
            [leftBC]=find_index(Items,im);

            Items = char(app.TopBCListBox.Items);
            im=app.TopBCListBox.Value;
            [topBC]=find_index(Items,im);

            Items = char(app.RightBCListBox.Items);
            im=app.RightBCListBox.Value;
            [rightBC]=find_index(Items,im);

            Items = char(app.BottomBCListBox.Items);
            im=app.BottomBCListBox.Value;
            [bottomBC]=find_index(Items,im);

            properties(app);

            app.L=str2num(app.BottomLengthinEditField.Value);
            app.W=str2num(app.SideWidthinEditField.Value);
            app.thick=str2num(app.ThicknessinEditField.Value);

            geometry_mass(app);
            
            a=app.L;
            b=app.W;
            
            a2=a^2;
            a4=a^4;
            b2=b^2;
            b4=b^4;
            
            a2b2=a^2*b^2;
            
            iflag=0;
            
            % case 1:  pinned-pinned-free-free
            if( (leftBC==3 && topBC==3 && rightBC==2 && bottomBC==2)||...
                (leftBC==2 && topBC==2 && rightBC==3 && bottomBC==3)||...
                (leftBC==2 && topBC==3 && rightBC==3 && bottomBC==2)||...
                (leftBC==3 && topBC==2 && rightBC==2 && bottomBC==3))
                app.fn=(pi/11)*app.sqDrho*( (1/a2) + (1/b2) );
                iflag=1;
            end
            
            % case 2:  pinned-pinned-free-pinned
            if( (leftBC==2 && topBC==2 && rightBC==3 && bottomBC==2)||...
                (leftBC==3 && topBC==2 && rightBC==2 && bottomBC==2))
                app.fn=(pi/2)*app.sqDrho*( (1/(4*a2)) + (1/b2) );
                iflag=1;
            end
            
            % case 3:  pinned-pinned-pinned-pinned
            if( leftBC==2 && topBC==2 && rightBC==2 && bottomBC==2)
                app.fn=(pi/2)*app.sqDrho*( (1/a2) + (1/b2) );
                iflag=1;
            end
            
            % case 4:  fixed-fixed-free-free
            if( (leftBC==3 && topBC==3 && rightBC==1 && bottomBC==1)||...
                (leftBC==1 && topBC==1 && rightBC==3 && bottomBC==3)||...
                (leftBC==1 && topBC==3 && rightBC==3 && bottomBC==1)||...
                (leftBC==3 && topBC==1 && rightBC==1 && bottomBC==3))
                app.fn=(pi/5.42)*app.sqDrho*sqrt( (1/a4) + (3.2/a2b2)+ (1/b4) );
                iflag=1;
            end
            
            % case 5:  fixed-fixed-fixed-free
            if( (leftBC==1 && topBC==1 && rightBC==3 && bottomBC==1)||...
                (leftBC==3 && topBC==1 && rightBC==1 && bottomBC==1))
                app.fn=(pi/3)*app.sqDrho*sqrt( (0.75/a4) + (2/a2b2)+ (12/b4) );
                iflag=1;
            end
            
            
            % case 6:  fixed-fixed-fixed-fixed
            if(leftBC==1 && topBC==1 && rightBC==1 && bottomBC==1)
                app.fn=(pi/1.5)*app.sqDrho*sqrt( (3/a4) + (2/a2b2) + (3/b4) );
                iflag=1;
            end
            
            % case 7:  fixed-pinned-fixed-pinned
            if(leftBC==1 && topBC==2 && rightBC==1 && bottomBC==2)
                app.fn=(pi/3.46)*app.sqDrho*sqrt( (16/a4) + (8/a2b2) + (3/b4) );
                iflag=1;
            end
            
            % case 8:  free-free-free-free
            if( leftBC==3 && topBC==3 && rightBC==3 && bottomBC==3)
                disp(' free-free-free-free  ');
                app.fn=(pi/2)*app.sqDrho*sqrt( 2.08/a2b2 );
                iflag=1;
            end
            
            % case 9:  fixed-free-free-free
            if( (leftBC==1 && topBC==3 && rightBC==3 && bottomBC==3)||...
                (leftBC==3 && topBC==3 && rightBC==1 && bottomBC==3))  
                app.fn=(0.56/a2)*app.sqDrho;
                iflag=1;
            end
            
            % case 10:  fixed-free-fixed-free
            if( leftBC==1 && topBC==3 && rightBC==1 && bottomBC==3) 
                app.fn=(3.55/a2)*app.sqDrho;
                iflag=1;
            end
            
            % case 11:  fixed-free-pinned-free
            if( (leftBC==1 && topBC==3 && rightBC==2 && bottomBC==3)||...
                (leftBC==2 && topBC==3 && rightBC==1 && bottomBC==3))  
                app.fn=(0.78*pi/a2)*app.sqDrho;
                iflag=1;
            end
            
            
            % case 12:  pinned-free-pinned-free
            if( leftBC==2 && topBC==3 && rightBC==2 && bottomBC==3) 
            %    app.fn=(pi/(2*a2))*app.sqDrho;
                omega= (9.87/a2)*app.sqDrho;  % Leissa, Table 4.20
                app.fn=omega/(2*pi);
                iflag=1;
            end
            
            % case 13:  fixed-pinned-fixed-free
            if( (leftBC==1 && topBC==2 && rightBC==1 && bottomBC==3)||...
                (leftBC==1 && topBC==3 && rightBC==1 && bottomBC==2))  
                app.fn=(pi/1.74)*app.sqDrho*sqrt((4/a4)+(1/(2*a2b2))+(1/(64*b4)));
                iflag=1;
            end
            
            % case 14:  fixed-pinned-free-free
            if( (leftBC==1 && topBC==2 && rightBC==3 && bottomBC==3)||...
                (leftBC==1 && topBC==3 && rightBC==3 && bottomBC==2)||... 
                (leftBC==3 && topBC==2 && rightBC==1 && bottomBC==3)||... 
                (leftBC==3 && topBC==3 && rightBC==1 && bottomBC==2)) 
                app.fn=(pi/2)*app.sqDrho*sqrt((0.127/a4)+(0.20/a2b2));
                iflag=1;
            end
            
            % case 15:  pinned-fixed-pinned-free
            if( (leftBC==2 && topBC==1 && rightBC==2 && bottomBC==3)||...
                (leftBC==2 && topBC==3 && rightBC==2 && bottomBC==1))  
                app.fn=(pi/2)*app.sqDrho*sqrt((1/a4)+(0.608/a2b2)+(0.126/b4));
                iflag=1;
            end    
            
            % case 16:  fixed-fixed-pinned-fixed
            if( (leftBC==2 && topBC==1 && rightBC==1 && bottomBC==1)||...
                (leftBC==1 && topBC==1 && rightBC==2 && bottomBC==1))  
                app.fn=(pi/2)*app.sqDrho*sqrt((2.45/a4)+(2.90/a2b2)+(5.13/b4));
                iflag=1;
            end
            
            % case 17:  fixed-fixed-free-pinned
            if( (leftBC==1 && topBC==1 && rightBC==3 && bottomBC==2)||...
                (leftBC==1 && topBC==2 && rightBC==3 && bottomBC==1)||... 
                (leftBC==3 && topBC==1 && rightBC==1 && bottomBC==2)||... 
                (leftBC==3 && topBC==2 && rightBC==1 && bottomBC==1)) 
                app.fn=(pi/2)*app.sqDrho*sqrt((0.127/a4)+(0.707/a2b2)+(2.44/b4));
                iflag=1;
            end
            
            % case 18:  fixed-fixed-pinned-pinned-pinned
            if( (leftBC==2 && topBC==2 && rightBC==1 && bottomBC==1)||...
                (leftBC==1 && topBC==1 && rightBC==2 && bottomBC==2)||... 
                (leftBC==1 && topBC==2 && rightBC==2 && bottomBC==1)||... 
                (leftBC==2 && topBC==1 && rightBC==1 && bottomBC==2)) 
                app.fn=(pi/2)*app.sqDrho*sqrt((2.45/a4)+(2.68/a2b2)+(2.45/b4));
                iflag=1;
            end
            
            
            % case 19:  fixed-pinned-pinned-pinned
            if( (leftBC==1 && topBC==2 && rightBC==2 && bottomBC==2)||...
                (leftBC==2 && topBC==2 && rightBC==1 && bottomBC==2))  
                app.fn=(pi/2)*app.sqDrho*sqrt((2.45/a4)+(2.32/a2b2)+(1/b4));
                iflag=1;
            end
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.thick>0.5*app.W || app.thick>0.5*app.L)
                wrndlg('app.thickness too large');
                return;
            end
            
            if(iflag==0)
                wrndlg('case unavailable');
                return;
            end

            if(iflag==1)

                ss=sprintf(' fn = %8.4g Hz \n\n',app.fn);

                if(app.iu==1)
                    ss=sprintf('%s total mass = %8.4g lbm \n\n',ss,386*app.total_mass);
                    ss=sprintf('%s plate stiffness D = %8.4g lbf-in',ss,app.D);
                else
                    ss=sprintf('%s total mass = %8.4g kg \n\n',ss,app.total_mass);
                    ss=sprintf('%s plate stiffness D = %8.4g N-m',ss,app.D);
                end

                app.ResultsTextArea.Value=ss;
                app.ResultsTextArea.Visible='on';
                app.ResultsTextAreaLabel.Visible='on';  

            end

        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ClearResults(app);
            ChangeMaterials(app);
        end

        % Value changed function: TopBCListBox
        function TopBCListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: BottomBCListBox
        function BottomBCListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ChangeMaterials(app);
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: UniformNonstructuralMasslbmEditField
        function UniformNonstructuralMasslbmEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function CalculatefnButton_2Pushed(app, event)
            ClearResults(app);

            Property(app);

            L_fixed=str2num(app.FixedEdgeLengthinEditField.Value);
            L_free=str2num(app.FreeEdgeLengthinEditField.Value);
            app.thick=str2num(app.ThicknessinEditField_2.Value);

            fmin=1.0e+90; 
            
            app.L=L_fixed;
            app.W=L_free;

            properties(app); 
            
            geometry_mass(app);

            h=app.thick;
            a=L_fixed;
            b=L_free;

            % 
            num=200; 
            nnn=num;
            dx=b/num;   % needed switch
            dy=a/num; 
            % 
            knum=800; 
            app.alpha_r=0; 
            app.theta_r=0;
            %
            fn1=(22.373/(2*pi))*sqrt(app.D/(app.rho*h))/b^2;
            %

            c1=2*app.mu;
            c2=2*(1-app.mu);
            %
            app.root=4.73004;
           
            bL=app.root;
            app.beta=app.root/b;
            beta2=app.beta^2;
            C=(sinh(bL)+sin(bL))/(cosh(bL)-cos(bL));  
            %
            x=zeros(nnn+1,1);
            y=zeros(nnn+1,1);

            for i=1:(nnn+1)
               ii=i-1;
               x(i)=ii*dx;
               y(i)=(ii*dy-(a/2));
            end 
            %    
            disp(' ');
            
            progressbar;
            
            for k=1:knum
                
                progressbar(k/knum);
                
                if(k<knum/10 || rand<0.5)
                    alpha=  -1.0 +2*rand;
                    theta=   5*rand/a;
                else
                    alpha=app.alpha_r*(0.98+0.04*rand);
                    theta=app.theta_r*(0.98+0.04*rand);    
                end
                theta2=theta^2;
            %
                if(k==1)
                    alpha=0;
                end
            %
                V=0;
                T=0;   
            %    
                argx=app.beta*x;
                argy=theta*y;
            %
                Z1A=0;
                Z2A=0;   
            %
                for i=1:nnn+1
                    cosh_arg=cosh(argx(i));
                    cos_arg=cos(argx(i));
                    sinh_arg=sinh(argx(i));
                    sin_arg=sin(argx(i));
            %        
                                P=(cosh_arg-cos_arg)-C*(sinh_arg-sin_arg);     
                        dPdx=app.beta*((sinh_arg+sin_arg)-C*(cosh_arg-cos_arg));
                     d2Pdx2=beta2*((cosh_arg+cos_arg)-C*(sinh_arg+sin_arg));
            %        
                    for j=1:nnn+1
                        cos_arg=cos(argy(j));
                        sin_arg=sin(argy(j));
                             WW=1+alpha*cos_arg;
                          dWdy=-alpha*theta*sin_arg;
                        d2Wdy2=-alpha*theta2*cos_arg;            
            %
                        Z=P*WW;
                        
                        Z1A=Z1A+Z;            
                        Z2A=Z2A+Z^2;         
            %    
                        dZdx  =   dPdx*WW; 
                        d2Zdx2= d2Pdx2*WW;     
            %
                        dZdy  = P*dWdy; 
                        d2Zdy2= P*d2Wdy2;  
            %    
                        d2Zdxdy=dPdx*dWdy;
            %
                        V1=(d2Zdx2)^2;
                        V2=(d2Zdy2)^2;
                        V3=c1*(d2Zdx2)*(d2Zdy2);
                        V4=c2*(d2Zdxdy)^2;
            %            
                        V=V+ V1+V2+V3+V4;
                        T=T+Z^2;
                    end
                end
                V=V*app.D/2;
                T=T*app.rho*h/2;
                omega=sqrt(V/T);
                app.fn=omega/(2*pi);
                if(app.fn<fmin)
                    fmin=app.fn;
                    app.alpha_r=alpha;
                    app.theta_r =theta;
                    
                    Z1A_r=Z1A;
                    Z2A_r=Z2A;        
                    
            
                    out1=sprintf('%d %8.4g Hz  alpha=%8.4g  theta=%8.4g %8.4g',k,app.fn,app.alpha_r,app.theta_r);
                    disp(out1);
                end
            end
            
            pause(0.3);
            progressbar(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                qq=dx*dy*h*app.rho;
            
                     ZA=Z1A_r*qq;
                    app.ZAA=Z2A_r*qq;
            %
                   app.ZAA=sqrt(app.ZAA);
                   
                app.part_r=(ZA/app.ZAA);  
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            clear xx;
            clear yy;
            clear zzr;
            %
            fig_num=1;
            figure(fig_num);
            fig_num=fig_num+1;
            zmax=0;
            zmin=0;
            %
            num=40; 
            
            dx=b/num;   % needed switch
            dy=a/num; 
            %
            clear arg;
            clear x;
            clear y;
            %
            for i=1:(num+1)
                      x=((i-1)*dx-(b/2));
                      yy(i)=x;
                      arg=app.beta*(x+b/2);
                      P=(cosh(arg)-cos(arg))-C*(sinh(arg)-sin(arg)); 
            %          
                    for j=1:(num+1)
                        y=((j-1)*dy-(a/2));
                        xx(j)=y;
                        arg=app.theta_r*y;
                          WW=1+app.alpha_r*cos(arg);
            %
                         zzr(i,j)=P*WW;
            %
                        if(zzr(i,j)>zmax)
                            zmax=zzr(i,j);
                        end
            %
                        if(zzr(i,j)<zmin)
                            zmin=zzr(i,j);
                        end            
                    end
            end
            %
            %
            zzr=zzr/(max(max(abs(zzr))));
            
            minxx=min(xx);
            minyy=min(yy);
            
            xx=xx-min(xx);
            yy=yy-min(yy);
            
            clear aaa;
            clear abc;
            xymax=max([max(xx) max(yy)]);
            
            zmin=0;
            zmax=2.5;
            
            surf(xx,yy,zzr);
            % xlim([0 xymax]) 
            % ylim([0 xymax]) 
            zlim([zmin zmax])
            xlabel('X');
            ylabel('Y');
            out1=sprintf('Rayleigh: fn=%8.4g Hz',fmin); 
            title(out1);
            
            %
            disp(' ');
            out1=sprintf(' Expected:  fn ~ %8.4g Hz   Rayleigh: fn=%8.4g Hz',fn1,fmin);
            disp(out1);
            %
            
            se=sprintf('Expected: fn ~ %8.4g Hz \n\n',fn1);
            sr=sprintf('Rayleigh: fn = %8.4g Hz \n\n',fmin);
            s1=sprintf(' %s %s',se,sr);


            if(app.iu==1)
                ss=sprintf('%s total mass = %8.4g lbm \n\n',s1,386*app.total_mass);
                ss=sprintf('%s plate stiffness D = %8.4g lbf-in',ss,app.D);
            else
                ss=sprintf('%s total mass = %8.4g kg \n\n',s1,app.total_mass);
                ss=sprintf('%s plate stiffness D = %8.4g N-m',ss,app.D);
            end

            app.ResultsTextArea_fixed_point.Value=ss;
            app.ResultsTextArea_fixed_point.Visible='on';
            app.ResultsTextArea_2Label.Visible='on';
        end

        % Button pushed function: CalculatefnButton_SS
        function CalculatefnButton_SSPushed(app, event)
         
            app.jflag=2;

            ClearResults(app);

            Property(app);

            Items = char(app.BCsListBox.Items);
            im=app.BCsListBox.Value;
            [app.nBC]=find_index(Items,im);

            app.L=str2num(app.BottomLengthinEditField_SS.Value);
            app.W=str2num(app.SideWidthinEditField_SS.Value);
            app.thick=str2num(app.ThicknessinEditField_SS.Value);

            geometry_mass(app);

            a=app.L;
            b=app.W;

            BBB=app.D;
           
            % sq_mass=sqrt(app.total_mass);

            app.DD=sqrt(app.D/(app.rho*app.thick));

            if(app.nBC==1) % Simply-Supported All Edges
                SS(app);
            end    
            if(app.nBC==2) % Fixed All Edges
                Fixed_all(app);
            end 
            if(app.nBC==3) % Fixed-Free-Fixed-Free
                fixed_free_fixed_free(app);        
            end            

            if(app.nBC~=1)
                if(app.iu==1)
                    xlabel('x (in)');
                    ylabel('y (in)');
                else
                    xlabel('x (m)');
                    ylabel('y (m)');                
                end    
                return;  % return for other than SS
            end    

            sz=size(app.fbig);
            
            kj=1;
            
            part_max=max(abs(app.fbig(:,4)));
            
            for i=1:sz(1)
                part=abs(app.fbig(i,4));
                if((part/part_max)>1.0e-03)
                    app.fbigs(kj,:)=app.fbig(i,:);
                    kj=kj+1;
                end
            end
                     
            %
            mt=length(app.fn);
            if(mt>16)
                mt=16;
            end
            
            disp(' ');
            disp('    fn(Hz)   m   n        PF    EMM ratio');
            for i=1:mt 
                out1=sprintf(' %9.5g \t %d\t %d\t %8.4g\t %8.4g  ',app.fbig(i,1),app.fbig(i,2),app.fbig(i,3),app.fbig(i,4),app.fbig(i,5)/app.total_mass);
                disp(out1);
                
            end
            disp(' ');
            
            n=12; 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            for i=1:n
                data_s{i,1}=sprintf('%d',i);
                data_s{i,2}=sprintf('%8.4g',app.fbig(i,1));
                data_s{i,3}=sprintf('%d',app.fbig(i,2));
                data_s{i,4}=sprintf('%d',app.fbig(i,3));
                data_s{i,5}=sprintf('%7.3g',app.fbig(i,4));
                data_s{i,6}=sprintf('%7.3f',app.fbig(i,5)/app.total_mass);
            end

            app.UITable_fn.Data=data_s;
            app.UITable_fn.Visible='on';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_fn,s) % adjust the style

            app.UITable_fn.Visible='on';   

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ioct=2;
            [fc,imax]=octaves(ioct);
            
            app.fn=app.fbig(:,1);
            
            mode_count=zeros(imax,1);
            modal_density=zeros(imax,2);
            
            fnL=length(app.fn);
            fcL=length(fc);
            
            for i=1:ceil(0.5*fnL)
                
                for j=1:(fcL-1)
                   
                    if(app.fn(i)> fc(j) && app.fn(i) < fc(j+1))
                        mode_count(j)=mode_count(j)+1;
                        break;
                    end    
                    
                end
                
            end
            
            for j=1:fcL
                
               bw=( 2^(1/6) -   1/(2^(1/6))  )*fc(j);
            
               if(mode_count(j) >=1)
                    modal_density(j,2)=mode_count(j)/bw;
               end
            end    
            
            
            fff=fc;
            aaa=modal_density(:,2);
            
            %% [ff,aa]=remove_zeros_all(fff,aaa);
            
            ff=fix_size(fff);
            aa=fix_size(aaa);
            
            [fx,ax]=remove_zeros_all(fff,aaa);
            
            
            fxL=length(fx);
            
            fmin=fx(1);
            fmax=fx(fxL-1);
            
            
            clear modal_density;
            
            modal_density=[ff aa];
            
            
            CCC=(app.area/(4*pi))*sqrt( app.rhos/BBB );
            
            ffL=length(ff);
            
            SEA_md=zeros(ffL,1);
            
            
            for i=1:ffL
                
                omega=2*pi*ff(i);
               
                term=CCC-0.25*((app.rhos/BBB)^(0.25))*(( app.L+app.W )/pi)/sqrt(omega);
                
                SEA_md(i)=2*pi*term;
                
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            num_modes=81;
            setappdata(0,'num_modes',num_modes);   
            %
            disp(' ');
            %
            dx=a/80;
            dy=b/80;
            %
            pa=pi/a;
            pb=pi/b;
            %
            clear z;
            clear zmn;
            %
            x=zeros(1,num_modes);
            y=zeros(1,num_modes);
            z=zeros(num_modes,num_modes);
            zmn=zeros(mt,num_modes,num_modes);
            %
            for i=1:num_modes
                x(i)=pa*(i-1)*dx;   
                y(i)=pb*(i-1)*dy;  
            end
            %
            xx=x*a/max(x);
            yy=y*b/max(y);
            %
            fig_num=1;

            for iv=1:mt
                for i=1:num_modes
                    term1=sin(app.fbig(iv,2)*x(i));
                    for j=1:num_modes    
                        z(i,j)=term1*sin(app.fbig(iv,3)*y(j));
                        zmn(iv,i,j)=z(i,j);
                    end        
                end
                if(iv<=6)
                    figure(fig_num);
                    fig_num=fig_num+1;
                    zzr=z';
                    surf(xx,yy,zzr);
                    xlabel('X');
                    ylabel('Y');
                    out1=sprintf('Mode %d   fn=%8.4g Hz',iv,app.fbig(iv,1));
                    title(out1);
                end
            %
            end
            %
            z=z*app.Amn;
            %
            
            x_label='Frequency (Hz)';
            y_label='Modal Density (modes/Hz)';
            t_string='Modal Density, Plate Bending, Simply Supported';
            
            ppp1=modal_density(1:(ffL-1),:);
            ppp2=[ff(1:(ffL-1)) SEA_md(1:(ffL-1))];
            
            leg1='Modal Analysis';
            leg2='SEA Theory';
            
            ymin=0;
            ymax=1.4*max(modal_density(:,2));
            
            [fig_num,h2]=plot_loglin_function_two_NW_ymin_ymax_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,ymin,ymax);           
           
        end

        % Callback function
        function CalculatefnButton_cornerPushed(app, event)
            ClearResults(app);

            Property(app);

            app.L=str2num(app.BottomLengthinEditField_corner.Value);
            app.W=str2num(app.SideWidthinEditField_corner.Value);
            app.thick=str2num(app.ThicknessinEditField_corner.Value);

            geometry_mass(app);

            fmin=1.0e+90; 
            
            h=app.thick;
            a=app.L;
            b=app.W;

            % 
            num=40;
            np1=num+1;
            
            nnn=num;
            dx=a/num; 
            dy=b/num; 
            pia=pi/a; 
            pib=pi/b; 
            % 
            knum=2000; 
            app.alpha_r=0; 
            app.beta_r=0;
            app.gamma_r=0;
            %
            fn1=1.13*sqrt(app.D/(app.rho*h))/a^2;
            fn2=1.13*sqrt(app.D/(app.rho*h))/b^2;
            fff=[fn1 fn2];
            flow=min(fff);
            fup=max(fff);
            %
            c1=2*app.mu;
            c2=2*(1-app.mu);
            %
            for k=1:knum
                alpha= 0.8*rand+0.2;
                beta = 0.8*rand+0.2;
                
                if(k==1)
                    beta=alpha;
                end
            %
                if(k>10 && rand > 0.8 )
                    alpha=app.alpha_r*(0.96+0.08*rand);
                    beta =app.beta_r*(0.96+0.08*rand);    
                end
            %
                aaa=[alpha beta];
                gamma= min(aaa)*rand;
            %    
                if(abs(a-b)<a/100)
                    alpha=beta;
                end
            %
                sum=alpha+beta+gamma;
                alpha=alpha/sum;
                beta =beta/sum;
                gamma=gamma/sum;
            %
                pia_alpha=pia*alpha;
                pib_beta =pib*beta;
                pia2_alpha=(pia^2)*alpha;
                pib2_beta =(pib^2)*beta;    
            %
                Z1A=0;
                Z2A=0;
                V=0;
                T=0;
                for i=0:nnn
                    x=(i*dx-(a/2))*pia;
                    cx=cos(x);
                    sx=sin(x);
                    for j=0:nnn
                        y=(j*dy-(b/2))*pib;
                        sy=sin(y);
                        cy=cos(y);
                        Z=alpha*cx + beta*cy + gamma*cx*cy;
            %
                        Z1A=Z1A+Z;            
                        Z2A=Z2A+Z^2;
            %
                        dZdx  =  -pia_alpha*(1+gamma*sy)*sx;
                        d2Zdx2=  -pia2_alpha*(1+gamma*sy)*cx;   
            %
                        dZdy  =  -pib_beta*(1+gamma*sx)*sy;
                        d2Zdy2=  -pib2_beta*(1+gamma*sx)*cy;  
            %    
                        d2Zdxdy=pia_alpha*pib_beta*gamma*sy*sx;
            %
                        V1=(d2Zdx2)^2;
                        V2=(d2Zdy2)^2;
                        V3=c1*(d2Zdx2)*(d2Zdy2);
                        V4=c2*(d2Zdxdy)^2;
            %            
                        V=V+ V1+V2+V3+V4;
                        T=T+Z^2;
                    end
                end
                V=V*app.D/2;
                T=T*app.rho*h/2;
                omega=sqrt(V/T);
                app.fn=omega/(2*pi);
                if(app.fn<fmin && app.fn>= flow)
                    fmin=app.fn;
                    app.alpha_r=alpha;
                    app.beta_r =beta;
                    app.gamma_r=gamma;
            %
                    out1=sprintf('%d %8.4g Hz  alpha=%8.4g  beta=%8.4g  gamma=%8.4g ',k,app.fn,app.alpha_r,app.beta_r,app.gamma_r);
                    disp(out1);
            %
                    Z1A_r=Z1A;
                    Z2A_r=Z2A;
            %
                end  
            %
            end
            %
                qq=dx*dy*h*app.rho;
            
                     ZA=Z1A_r*qq;
                    app.ZAA=Z2A_r*qq;
            %
                   app.ZAA=sqrt(app.ZAA);
                   
                part_r=(ZA/app.ZAA);  
            
            %
            sflow=sprintf(' Lower bound f= %8.4g Hz \n',flow);
              sup=sprintf('Upper bound f= %8.4g Hz \n\n',fup);
            
            sest=sprintf('Estimated fn=%8.4g Hz \n\n',fmin);
            
                if(app.iu==1)
                    ss=sprintf('total mass = %8.4g lbm \n\n',386*app.total_mass);
                    ss=sprintf('%s plate stiffness D = %8.4g lbf-in',ss,app.D);
                else
                    ss=sprintf('total mass = %8.4g kg \n\n',app.total_mass);
                    ss=sprintf('%s plate stiffness D = %8.4g N-m',ss,app.D);
                end

             s1=sprintf('%s %s %s %s',sflow,sup,sest,ss);
                          
            %
            clear xx;
            clear yy;
            clear zzr;
            %
            
            fig_num=1;
            figure(fig_num);  
            fig_num=fig_num+1;
            setappdata(0,'fig_num',fig_num);
            
            xx=zeros(np1,1);
            yy=zeros(np1,1);
            zzr=zeros(np1,np1);
            %
            for i=1:np1 
                    xx(i)=((i-1)*dx-(a/2));
                    cx=cos(xx(i)*pia);
            %        sx=sin(xx(i)*pia);
                    for j=1:np1
                        yy(j)=((j-1)*dy-(b/2));
            %            sy=sin(yy(j));
                        cy=cos(yy(j)*pib);
                        zzr(i,j)=app.alpha_r*cx + app.beta_r*cy + app.gamma_r*cx*cy;
                    end
            end
            
            zzr=zzr/(max(max(abs(zzr))));
            
            xxmin=min(xx);
            yymin=min(yy);
            
            xx=xx-xxmin;
            yy=yy-yymin;
            
            clear aaa;
            clear abc;
            abc=[max(xx) max(yy)];
            aaa=max(abc);
            xmin=-aaa;
            ymin=-aaa;
            zmin=0;
            zmax=3;
            xmax=aaa;
            ymax=aaa;
            surf(xx,yy,zzr);
            % xlim([xmin xmax]) 
            % ylim([ymin ymax]) 
            zlim([zmin zmax]) 
            xlabel('X');
            ylabel('Y');
            
            out1=sprintf('fn=%8.4g Hz',fmin);
            title(out1);
            
            app.ResultsTextArea_corner.Value=s1;
            app.ResultsTextArea_corner.Visible='on';
            app.ResultsTextArea_cornerLabel.Visible='on';  

        end

        % Value changing function: BottomLengthinEditField_SS
        function BottomLengthinEditField_SSValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: SideWidthinEditField_SS
        function SideWidthinEditField_SSValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: ThicknessinEditField_SS
        function ThicknessinEditField_SSValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function BottomLengthinEditField_cornerValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function SideWidthinEditField_cornerValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function ThicknessinEditField_cornerValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function CalculatefnButton_fixedPushed(app, event)
            ClearResults(app);

            Property(app);

            app.L=str2num(app.BottomLengthinEditField_fixed.Value);
            app.W=str2num(app.SideWidthinEditField_fixed.Value);
            app.thick=str2num(app.ThicknessinEditField_fixed.Value);

            geometry_mass(app);

            fig_num=1;             
         
            a=app.L;
            b=app.W;
            h=app.thick;
            
            %%%

            psave=2;
            
            [app.fn,zzr,fig_num,P,dPdx,d2Pdx2,WW,dWdy,d2Wdy2,norm,PF]=...
            fixed_fixed_fixed_fixed_plate_RayleighG(app.D,app.rho,h,a,b,app.mu,app.rhos,fig_num,psave);
            
            ss=sprintf(' fn = %8.4g Hz \n\n',app.fn);

            if(app.iu==1)
                ss=sprintf('%s total mass = %8.4g lbm \n\n',ss,386*app.total_mass);
                ss=sprintf('%s plate stiffness D = %8.4g lbf-in',ss,app.D);
            else
                ss=sprintf('%s total mass = %8.4g kg \n\n',ss,app.total_mass);
                ss=sprintf('%s plate stiffness D = %8.4g N-m',ss,app.D);
            end

            app.ResultsTextArea_fixed.Value=ss;
            app.ResultsTextArea_fixed.Visible='on';
            app.ResultsTextArea_fixedLabel.Visible='on';

        end

        % Callback function
        function BottomLengthinEditField_fixedValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function SideWidthinEditField_fixedValueChanging(app, event)
                  
            ClearResults(app)      
        end

        % Callback function
        function ThicknessinEditField_fixedValueChanging(app, event)

            ClearResults(app);            
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
            
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            
            ClearResults(app);

            n=70;

            app.dm=zeros(n,n);
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            app.UniformQEditField.Value

            if(contains(nD,'Uniform'))
                if(contains(nmn,'Amplification')) 
                    Q=str2num(app.UniformQEditField.Value);
                    v=1/(2*Q);
                else  
                    v=str2num(app.UniformQEditField.Value);
                end   
                app.dm=ones(n,n)*v;      
            else

                A=char(app.UITable.Data);
            
                B=str2num(A);

                N=12;
                BB=B((N+1):(2*N)); 

                if(contains(nmn,'Amplification')) 
                    Q=BB;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(app.fbig(i,2),app.fbig(i,3))=v;
                    end
                else  
                    vd=BB;
                    for i=1:length(vd)
                        v=vd{1};
                        app.dm(app.fbig(i,2),app.fbig(i,3))=v;
                    end            
                end   
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            fig_num=1;

            damp=app.dm;  % check damping in functions

            Items = char(app.ResponseLocationListBox.Items);
            im=app.ResponseLocationListBox.Value;
            [nresp]=find_index(Items,im);

            num_modes=str2num(app.NumberofModesIncludedListBox.Value);
            
            fmin=str2num(app.MinEditField.Value);
            fmax=str2num(app.MaxEditField.Value);
            
            app.DD=sqrt(app.D/(app.rho*app.thick));
    
            app.Amn=2/sqrt(app.total_mass);  

            fprintf('\n %8.4g  %8.4g \n',app.total_mass,app.Amn);

            force_x=str2num(app.AlongLengthEditField.Value);
            force_y=str2num(app.AlongWidthEditField.Value);
                      
            if(nresp==1)
                resp_loc=[ 0.5*app.L   0.5*app.W ];
            end    
            if(nresp==2)
                resp_loc=[ force_x*app.L   force_y*app.W ]/100;
            end     
            if(nresp==3)
                   
                ns=21;
                    
                x_resp_loc=zeros(ns,2);   
                y_resp_loc=zeros(ns,2);         
                    
                dx=app.L/(ns-1);
                dy=app.W/(ns-1);
                    
                for i=1:ns
                    for j=1:ns
                        x_resp_loc(i,j)=(i-1)*dx;                
                        y_resp_loc(i,j)=(j-1)*dy;
                    end 
                end
            end    
            
            force_x=force_x*app.L/100;
            force_y=force_y*app.W/100;
             
            app.fn=app.fbig(:,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            nf=20000;
            f(1)=fmin;
            for k=2:nf
                f(k)=f(k-1)*2^(1/48);
                if(f(k)>fmax)
                    break;
                end    
            end
            nf=max(size(f));
            %
            tpi=2*pi; 
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            

            disp(' ');
            disp(' * * * * * * *  ');   
            
            if(nresp<=2)
            %
                H=zeros(nf,1);
                Hv=zeros(nf,1);
                HA=zeros(nf,1);   
            %
                progressbar;
                for k=1:nf
                    
                    progressbar(k/nf);
                    
                     H(k)=0;
                    Hv(k)=0;
                    HA(k)=0;      
             %       
                    om=tpi*f(k);
             %
                    if(app.nBC==1)
                        fff=app.fbig(1:num_modes,:); 
                        [qH,qHv,qHA]=ss_plate_frf_core_alt(app.L,app.W,resp_loc,force_x,force_y,damp,om,app.Amn,fff);
                    end

                    H(k)=qH;
                    Hv(k)=qHv;
                    HA(k)=qHA;
             %
                end

            %
                pause(0.2);
                progressbar(1);
            %
                if(app.iu==1)
                    HA=HA/386;
                else
                    HA=HA/9.81;
                end
            
                HA=abs(HA);
                Hv=abs(Hv);
                H=abs(H);

                maxH=0;
                maxHv=0;
                maxHA=0;
            
            %
                for k=1:nf
                    if(H(k)>maxH)
                        maxH=H(k);
                        maxF=f(k);
                    end
                    if(Hv(k)>maxHv)
                        maxHv=Hv(k);
                        maxFv=f(k);
                    end      
                    if(HA(k)>maxHA)
                        maxHA=HA(k);
                        maxFA=f(k);
                    end
                  
                end       
            %
                x=resp_loc(1);
                y=resp_loc(2);
            %
                x_label='Frequency (Hz)';
                md=6;
            
                f=fix_size(f);
                H=fix_size(H);
                Hv=fix_size(Hv);
                HA=fix_size(HA);
                
            %%    
                
                if(app.iu==1)
                    t_string=sprintf('Receptance FRF  x=%g in  y=%g in',x,y);
                    y_label='Disp(in) / Force(lbf)';
                else
                    t_string=sprintf('Receptance FRF  x=%g m  y=%g m',x,y);
                    y_label='Disp(m) / Force(N)';        
                end    
                ppp=[f H];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%
            
                if(app.iu==1)
                    t_string=sprintf('Mobility FRF  x=%g in  y=%g in',x,y);
                    y_label='Vel(in/sec)/ Force(lbf) ';
                else
                    t_string=sprintf('Mobility FRF  x=%g m  y=%g m',x,y);
                    y_label='Vel(m/sec)/ Force(N) ';        
                end
            
                ppp=[f Hv];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%
            
                if(app.iu==1)
                    t_string=sprintf('Accelerance FRF  x=%g in  y=%g in',x,y);
                    y_label='Accel(G) / Force(lbf) ';
                else
                    t_string=sprintf('Accelerance FRF  x=%g m  y=%g m',x,y);
                    y_label='Accel(G) / Force(N) ';
                end
            
                ppp=[f HA];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %
              
                disp(' ');
                disp('   Maximum FRF Values      ');
            %
                if(app.iu==1)
                    out1=sprintf('    Receptance = %8.3g (in/lbf) at %8.4g Hz ',maxH,maxF);
                    out2=sprintf('      Mobility = %8.3g (in/sec/lbf) at %8.4g Hz ',maxHv,maxFv);
                    out3=sprintf('   Accelerance = %8.3g (G/lbf) at %8.4g Hz ',maxHA,maxFA);        
                else
                    out1=sprintf('    Receptance = %8.3g (m/N) at %8.4g Hz ',maxH,maxF);
                    out2=sprintf('      Mobility = %8.3g (m/sec/N) at %8.4g Hz ',maxHv,maxFv); 
                    out3=sprintf('   Accelerance = %8.3g (G/lbf) at %8.4g Hz ',maxHA,maxFA);          
                end
                disp(out1);
                disp(out2);
                disp(out3);    
            %   
                disp(' ');
            
                ss=sprintf(' Maximum FRF Values \n\n %s \n %s \n %s ',out1,out2,out3);

            end     
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(nresp==3)  % spatial average
            %
                H=zeros(nf,1);   % leave here
                Hv=zeros(nf,1);
                HA=zeros(nf,1);
            
                progressbar;
                    
                for iv=1:ns
             
                    progressbar(iv/ns); 
                    
                    for iw=1:ns
                
                        for k=1:nf
             %       
                            om=tpi*f(k);
                                            
                            x_resp_loc(i,j)=(i-1)*dx;                
                            y_resp_loc(i,j)=(j-1)*dy;    
                            
                            qresp_loc=[ x_resp_loc(iv,iw)   y_resp_loc(iv,iw) ];                                
             %
                            fff=app.fbig(1:num_modes,:);
                            if(app.nBC==1)
                                [qH,qHv,qHA]=ss_plate_frf_core_alt(app.L,app.W,qresp_loc,force_x,force_y,damp,om,app.Amn,fff);
                            end
                            H(k)=H(k)+qH;
                            Hv(k)=Hv(k)+qHv;
                            HA(k)=HA(k)+qHA;
             %
                        end
                    end
                end
                   
                ns2=ns^2;
                
                H=H/ns2;
                Hv=Hv/ns2;
                HA=HA/ns2;
                
            %
                pause(0.2);
                progressbar(1);
            %
                if(app.iu==1)
                    HA=HA/386;
                else
                    HA=HA/9.81;
                end
            
                HA=abs(HA);
                Hv=abs(Hv);
                H=abs(H);
            
                maxH=0;
                maxHv=0;
                maxHA=0;
            
            %
                for k=1:nf
                    if(H(k)>maxH)
                        maxH=H(k);
                        maxF=f(k);
                    end
                    if(Hv(k)>maxHv)
                        maxHv=Hv(k);
                        maxFv=f(k);
                    end      
                    if(HA(k)>maxHA)
                        maxHA=HA(k);
                        maxFA=f(k);
                    end
                  
                end       
            %
                x_label='Frequency (Hz)';
                md=6;
            
                f=fix_size(f);
                H=fix_size(H);
                Hv=fix_size(Hv);
                HA=fix_size(HA);
                
            %%    
                
                if(app.iu==1)
                    t_string=sprintf('Spatial Average Receptance FRF');
                    y_label='Disp(in) / Force(lbf)';
                else
                    t_string=sprintf('Spatial Average Receptance FRF');
                    y_label='Disp(m) / Force(N)';        
                end    
                ppp=[f H];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%
            
                if(app.iu==1)
                    t_string=sprintf('Spatial Average Mobility FRF');
                    y_label='Vel(in/sec)/ Force(lbf) ';
                else
                    t_string=sprintf('Spatial Average Mobility FRF');
                    y_label='Vel(m/sec)/ Force(N) ';        
                end
            
                ppp=[f Hv];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%
            
                if(app.iu==1)
                    t_string=sprintf('Spatial Average Accelerance FRF');
                    y_label='Accel(G) / Force(lbf) ';
                else
                    t_string=sprintf('Spatial Average Accelerance FRF');
                    y_label='Accel(G) / Force(N) ';
                end
            
                ppp=[f HA];
                [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            %%
              
                disp(' ');
                disp('   Spatial Average FRF Values      ');
            %
                if(app.iu==1)
                    out1=sprintf('    Receptance = %8.3g (in/lbf) at %8.4g Hz ',maxH,maxF);
                    out2=sprintf('      Mobility = %8.3g (in/sec/lbf) at %8.4g Hz ',maxHv,maxFv);
                    out3=sprintf('   Accelerance = %8.3g (G/lbf) at %8.4g Hz ',maxHA,maxFA);        
                else
                    out1=sprintf('    Receptance = %8.3g (m/N) at %8.4g Hz ',maxH,maxF);
                    out2=sprintf('      Mobility = %8.3g (m/sec/N) at %8.4g Hz ',maxHv,maxFv); 
                    out3=sprintf('   Accelerance = %8.3g (G/lbf) at %8.4g Hz ',maxHA,maxFA);          
                end
                disp(out1);
                disp(out2);
                disp(out3);    
            %   
                disp(' ');     

                ss=sprintf(' Spatial Average FRF Values \n\n %s \n %s \n %s ',out1,out2,out3);
                
            end
            
            app.receptance=[f H];
            app.mobility=[f Hv];
            app.accelerance=[f HA];
             
            app.ResultsTextArea_point_force_FRF.Value=ss;         
            app.ResultsTextArea_point_force_FRF.Visible='on';
            app.ResultsTextArea_point_force_FRF_Label.Visible='on';

        end

        % Value changing function: AlongLengthEditField
        function AlongLengthEditFieldValueChanging(app, event)
      
            ClearResults(app);
        end

        % Value changing function: AlongWidthEditField
        function AlongWidthEditFieldValueChanging(app, event)
            
            ClearResults(app);
        end

        % Value changing function: MinEditField
        function MinEditFieldValueChanging(app, event)
        
            ClearResults(app);
        end

        % Value changing function: MaxEditField
        function MaxEditFieldValueChanging(app, event)
            
            ClearResults(app);
        end

        % Value changed function: ResponseLocationListBox
        function ResponseLocationListBoxValueChanged(app, event)
           
            ClearResults(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = char(app.FRFTypeListBox.Items);
            im=app.FRFTypeListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.receptance;
            end
            if(n==2)
                data=app.mobility;
            end
            if(n==3)
                data=app.accelerance;
            end

            name=app.OutputArrayNameEditField.Value;

            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                wrndlg('Save failed')
            end    

        end

        % Button pushed function: CalculateButton_pft
        function CalculateButton_pftPushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            fig_num=1;

            damp=app.dm;  % check damping in functions
      
            num_modes=str2num(app.NumberofModesIncludedListBox.Value);

            a=app.L;
            b=app.W;
            h=app.thick;
            app.fn=app.fbig(:,1);
            % PF=app.fbig(:,4);
            
            m_index=app.fbig(:,6);
            n_index=app.fbig(:,7);
            
            force_x=str2num(app.AlongLengthEditField.Value);
            force_y=str2num(app.AlongWidthEditField.Value);
            
            force_x=force_x*app.L/100;
            force_y=force_y*app.W/100;
                   
            Items = char(app.ResponseLocationListBox_pft.Items);
            im=app.ResponseLocationListBox_pft.Value;
            [n_loc]=find_index(Items,im);

            if(n_loc==1)
                resp_loc=[ 0.5*app.L   0.5*app.W ];
            end
            if(n_loc==2)
                resp_loc=[ 0.5*app.L   0.25*app.W ];
            end
            if(n_loc==3)
                resp_loc=[ 0.25*app.L   0.5*app.W ];
            end
            if(n_loc==4)
                resp_loc=[ 0.25*app.L   0.25*app.W ];
            end
            if(n_loc==5)
                resp_loc=[ force_x   force_y ];
            end
            
            x=resp_loc(1,1);
            y=resp_loc(1,2);
            
            pax=x*pi/a;
            pby=y*pi/b;
            
            pax_pf=force_x*pi/a;
            pby_pf=force_y*pi/b;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input array failed');
                return;
            end

            t=app.THM(:,1);
            num_steps=length(t);
                  
            applied_force=app.THM(:,2);
             
            dt=mean(diff(t));
            %
            sr=1/dt;
            %
                disp(' ')
                disp(' Time Step ');
                dtmin=min(diff(t));
                dtmax=max(diff(t));
            %
                out4 = sprintf(' dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf(' dt     = %8.4g sec  ',dt);
                out6 = sprintf(' dtmax  = %8.4g sec  ',dtmax);
                disp(out4)
                disp(out5)
                disp(out6)
            %
                disp(' ')
                disp(' Sample Rate ');
                out4 = sprintf(' srmin  = %8.4g samples/sec  ',1/dtmax);
                out5 = sprintf(' sr     = %8.4g samples/sec  ',1/dt);
                out6 = sprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
                disp(out4)
                disp(out5)
                disp(out6)
            %
            if(((dtmax-dtmin)/dt)>0.01)
                disp(' ')
                disp(' Warning:  time step is not constant.')
                return;
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
            
            rd=zeros(num_steps,1);
            rv=zeros(num_steps,1);
            ra=zeros(num_steps,1);
            %
            sxx=zeros(num_steps,1);
            syy=zeros(num_steps,1);
            txy=zeros(num_steps,1);
            %
            nrd=zeros(num_steps,num_modes);
            nrv=zeros(num_steps,num_modes);
            nra=zeros(num_steps,num_modes); 
            %
            
                [a1,a2,ra_b1,ra_b2,ra_b3,rv_b1,rv_b2,rv_b3,rd_b1,rd_b2,rd_b3]=...
                            srs_coefficients_avd(app.fn(1:num_modes),damp(1:num_modes),dt);
            
            %
                fmax=sr/10;
            %
                out1=sprintf('\n maximum frequency limit for modal transient analysis: fmax=%9.5g Hz \n',fmax);
                disp(out1);

                progressbar;
                for j=1:num_modes
                    progressbar(j/num_modes);
            %
                    if( app.fn(j)<fmax)
            %
                        if(app.nBC==1)
                            yp=applied_force*app.Amn*sin(m_index(j)*pax_pf)*sin(n_index(j)*pby_pf);
                        end    
            %
                        back   =[     1, -a1(j), -a2(j) ];
            %
            %   velocity
            %
                        clear forward;
                        forward=[ rv_b1(j),  rv_b2(j),  rv_b3(j) ];    
                        nrv(:,j)=filter(forward,back,yp);
            %
            %   displacement
            %
                        clear forward;
                        forward=[ rd_b1(j),  rd_b2(j),  rd_b3(j) ];      
                        nrd(:,j)=filter(forward,back,yp);
                        nbm(:,j)=nrd(:,j);
            %
            %   acceleration
            %
                        clear forward;  
                        forward=[ ra_b1(j),  ra_b2(j),  ra_b3(j) ];    
                        nra(:,j)=filter(forward,back,yp); 
            %
            %
                        if(app.nBC==1)
                            ZZ=app.Amn*sin(m_index(j)*pax)*sin(n_index(j)*pby);                 
            %     
                            rd(:)= rd(:) +ZZ*nrd(:,j);
                            rv(:)= rv(:) +ZZ*nrv(:,j);             
                            ra(:)= ra(:) +ZZ*nra(:,j); 
            %
                            ZZxx=-(pi*m_index(j)/a)^2*ZZ;
                            ZZyy=-(pi*n_index(j)/b)^2*ZZ;
                            ZZxy= (pi^2/(a*b))*m_index(j)*n_index(j)*cos(m_index(j)*pax)*cos(n_index(j)*pby);
                        end
            %
                        sxx(:)=sxx(:)+ (ZZxx+app.mu*ZZyy)*nrd(:,j);
                        syy(:)=syy(:)+ (app.mu*ZZxx+ZZyy)*nrd(:,j);
                        txy(:)=txy(:)+ ZZxy*nrd(:,j);
            %
                    end
            %
                end
                pause(0.3);
                progressbar(1);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ');
                disp(' calculating stress...');
            
                acc=ra;
            %
                if(app.iu==1)
                    acc=acc/386;     
                else
                    acc=acc/9.81;
                    rd=rd*1000;      
                end
            %
                z=h/2;
                term1=-(app.E*z/(1-app.mu^2));    
                term2=-(app.E*z/(1+app.mu));
            %
                sxx=sxx*term1;
                syy=syy*term1;
                txy=txy*term2;
            %
                clear length;
                n=length(sxx);
                
                [vM,Tr,~,~]=von_Mises_Tresca_plane_stress(sxx,syy,txy);
            
            %
                disp(' ');
                disp(' Peak Response Values ');
                disp(' ');
                out1=sprintf('          Acceleration = %8.4g G',max(abs(acc)));
            %   
             
                if(app.iu==1)
                    out2=sprintf('     Velocity = %8.4g in/sec',max(abs(rv)));          
                    out3=sprintf(' Displacement = %8.4g in',max(abs(rd)));  
                    out4=sprintf('\n      von Mises Stress = %8.4g psi',max(abs(vM)));
                    out5=sprintf('         Tresca Stress = %8.4g psi',max(abs(Tr)));  
                    ss='psi';
                    sq=sprintf('Peak Response Values \n\n %s \n %s \n %s \n %s \n %s \n %s \n %s',...
                                          out1,out2,out3,out4,out5);             
                else
                    out2=sprintf('     Velocity = %8.4g m/sec',max(abs(rv)));         
                    out3=sprintf(' Displacement = %8.4g mm',max(abs(rd)));
                    out4=sprintf('\n      von Mises Stress = %8.4g Pa',max(abs(vM)));
                    out5=sprintf('                       = %8.4g MPa',max(abs(vM))/(1.0e+06)); 
                       out6=sprintf('         Tresca Stress = %8.4g Pa',max(abs(Tr)));    
                       out7=sprintf('                       = %8.4g Pa',max(abs(Tr))/(1.0e+06));        
                    ss='Pa';
                    sq=sprintf('Peak Response Values \n\n %s \n %s \n %s \n %s \n %s \n %s \n %s',...
                                          out1,out2,out3,out4,out5,out6,out7);
                end
            %    
                disp(out1);
                disp(out2);
                disp(out3);
                disp(out4);
                disp(out5);
                
                if(app.iu==2)
                    disp(out6);
                    disp(out7);       
                end
                
                vel=max(abs(rv));
                
              
            % [Hunt_peak]=Hunt_plate_stress_shock(app.E,app.rho,app.mu,a,b,vel,ss);            
                    
            %
            %
            t=fix_size(t);
            acc=fix_size(acc);
            rv=fix_size(rv);
            rd=fix_size(rd);
            vM=fix_size(vM);
            sxx=fix_size(sxx);
            syy=fix_size(syy);
            txy=fix_size(txy);
            %
            
            disp(' ');
            disp(' generating plots... ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                figure(fig_num)
                fig_num=fig_num+1;
            %
                plot(t,applied_force);
            %
                if(app.iu==1)
                    out1=sprintf('Applied Force at x=%g in  y=%g in',force_x,force_y);
                    ylabel('Force (lbf) ');
                else
                    out1=sprintf('Applied Force at x=%g m  y=%g m',force_x,force_y);
                    ylabel('Force (Pa) ');
                end
                
                title(out1);
                
                xlabel('Time(sec)'); 
                grid on;
            
                [fig_num]=plate_stress_plots(fig_num,x,y,t,rd,rv,acc,sxx,syy,txy,vM,Tr,app.iu);
            %
            %
            acc=[t acc];
            rv=[t rv];
            rd=[t rd];
            vMs=[t vM];
            stensor=[t sxx syy txy];

            app.acceleration=acc;
            app.relative_velocity=rv;
            app.relative_displacement=rd;
            app.bending_stress=vMs;
            app.bending_stress_tensor=stensor;
            
            app.ResultsTextArea_pft.Value=sq;         
            app.ResultsTextArea_pft.Visible='on';
            app.ResultsTextArea_Label_pft.Visible='on';


        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
    
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed(app, event)
            update(app);
        end

        % Button pushed function: SaveButton_pft
        function SaveButton_pftPushed(app, event)
            
            Items = char(app.TimeHistoryListBox.Items);
            im=app.TimeHistoryListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.acceleration;
            end
            if(n==2)
                data=app.relative_velocity;
            end
            if(n==3)
                data=app.relative_displacement;
            end            
            if(n==4)
                data=app.bending_stress;
            end
            if(n==5)
                data=app.bending_stress_tensor;
            end           

            name=app.OutputArrayNameEditField_pft.Value;

            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                wrndlg('Save failed')
            end               
            

        end

        % Button pushed function: CalculateButton_pressure
        function CalculateButton_pressurePushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            fig_num=1;

            damp=app.dm;  % check damping in functions
      
            num_modes=str2num(app.NumberofModesIncludedListBox.Value);

            a=app.L;
            b=app.W;
            h=app.thick;
                   
            app.fn=app.fbig(:,1);
            
            force_x=str2num(app.AlongLengthEditField.Value);
            force_y=str2num(app.AlongWidthEditField.Value);
            
            force_x=force_x*app.L/100;
            force_y=force_y*app.W/100;
                   
            Items = char(app.ResponseLocationListBox_pft.Items);
            im=app.ResponseLocationListBox_pft.Value;
            [n_loc]=find_index(Items,im);

            if(n_loc==1)
                resp_loc=[ 0.5*app.L   0.5*app.W ];
            end
            if(n_loc==2)
                resp_loc=[ 0.5*app.L   0.25*app.W ];
            end
            if(n_loc==3)
                resp_loc=[ 0.25*app.L   0.5*app.W ];
            end
            if(n_loc==4)
                resp_loc=[ 0.25*app.L   0.25*app.W ];
            end
            if(n_loc==5)
                resp_loc=[ force_x   force_y ];
            end

            %%      
                   
            app.fn=app.fbig(:,1);
            
            m_index=app.fbig(:,6);
            n_index=app.fbig(:,7);
            force_part=app.fbig(:,8);
            
            x=resp_loc(1);
            y=resp_loc(2);

            pax=x*pi/a;
            pby=y*pi/b;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input array failed');
                return;
            end

            %
            t=app.THM(:,1);
            num_steps=length(t);
            num=num_steps;
                
            pressure=app.THM(:,2);
            
            dt=mean(diff(t));

            %
            sr=1/dt;
            %
            disp(' ')
            disp(' Time Step ');
            dtmin=min(diff(t));
            dtmax=max(diff(t));
            %
            out4 = sprintf(' dtmin  = %8.4g sec  ',dtmin);
            out5 = sprintf(' dt     = %8.4g sec  ',dt);
            out6 = sprintf(' dtmax  = %8.4g sec  ',dtmax);
            disp(out4)
            disp(out5)
            disp(out6)
            %
            disp(' ')
            disp(' Sample Rate ');
            out4 = sprintf(' srmin  = %8.4g samples/sec  ',1/dtmax);
            out5 = sprintf(' sr     = %8.4g samples/sec  ',1/dt);
            out6 = sprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
            disp(out4)
            disp(out5)
            disp(out6)
            %
            if(((dtmax-dtmin)/dt)>0.01)
                disp(' ')
                disp(' Warning:  time step is not constant.')
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
                    
                   rd=zeros(num_steps,1);
                   rv=zeros(num_steps,1);
                   ra=zeros(num_steps,1);
            %
                   sxx=zeros(num_steps,1);
                   syy=zeros(num_steps,1);
                   txy=zeros(num_steps,1);
            %
                    nrd=zeros(num_steps,num_modes);
                    nrv=zeros(num_steps,num_modes);
                    nra=zeros(num_steps,num_modes); 
            %
                [a1,a2,ra_b1,ra_b2,ra_b3,rv_b1,rv_b2,rv_b3,rd_b1,rd_b2,rd_b3]=...
                            srs_coefficients_avd(app.fn(1:num_modes),damp(1:num_modes),dt);
            %
                fmax=sr/10;
            %
                out1=sprintf('\n maximum frequency limit for modal transient analysis: fmax=%9.5g Hz \n',fmax);
                disp(out1);
            %
                for j=1:num_modes
            %
                    if(abs(force_part(j))>0 && app.fn(j)<fmax)
            %
                        yp=pressure*force_part(j);
            %
                        back   =[     1, -a1(j), -a2(j) ];
            %
            %   velocity
            %
                        clear forward;
                        forward=[ rv_b1(j),  rv_b2(j),  rv_b3(j) ];    
                        nrv(:,j)=filter(forward,back,yp);
            %
            %   displacement
            %
                        clear forward;
                        forward=[ rd_b1(j),  rd_b2(j),  rd_b3(j) ];      
                        nrd(:,j)=filter(forward,back,yp);
                        nbm(:,j)=nrd(:,j);
            %
            %   acceleration
            %
                        clear forward;  
                        forward=[ ra_b1(j),  ra_b2(j),  ra_b3(j) ];    
                        nra(:,j)=filter(forward,back,yp); 
            %
                        if(pp.nBC==1)
                            ZZ=app.Amn*sin(m_index(j)*pax)*sin(n_index(j)*pby);                 
            %     
                            rd(:)= rd(:) +ZZ*nrd(:,j);
                            rv(:)= rv(:) +ZZ*nrv(:,j);             
                            ra(:)= ra(:) +ZZ*nra(:,j); 
            %
                            ZZxx=-(pi*m_index(j)/a)^2*ZZ;
                            ZZyy=-(pi*n_index(j)/b)^2*ZZ;
                            ZZxy= (pi^2/(a*b))*m_index(j)*n_index(j)*cos(m_index(j)*pax)*cos(n_index(j)*pby);
                        end   
            %
                         sxx(:)=sxx(:)+ (ZZxx+app.mu*ZZyy)*nrd(:,j);
                         syy(:)=syy(:)+ (app.mu*ZZxx+ZZyy)*nrd(:,j);
                         txy(:)=txy(:)+ ZZxy*nrd(:,j);
            %
                    end
            %
                end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %       
                acc=ra+pressure;
            %
                if(app.iu==1)
                    acc=acc/386;
                else
                    acc=acc/9.81;
                    rd=rd*1000;    
                end
            %
                z=h/2;
                term1=-(app.E*z/(1-app.mu^2));    
                term2=-(app.E*z/(1+app.mu));
            %
                sxx=sxx*term1;
                syy=syy*term1;
                txy=txy*term2;
            %
                clear length;
                n=length(sxx);
                vM=zeros(n,1);
                Tr=zeros(n,1);
                
                for i=1:n
                    [vM(i),Tr(i),~,~]=von_Mises_Tresca_plane_stress(sxx(i),syy(i),txy(i));
                end
            %
                disp(' ');
                disp(' Peak Response Values ');
                disp(' ');
                out1=sprintf('          Acceleration = %8.4g G',max(abs(acc)));
            %   
             
                if(app.iu==1)
                    out2=sprintf('     Velocity = %8.4g in/sec',max(abs(rv)));          
                    out3=sprintf(' Displacement = %8.4g in',max(abs(rd)));  
                    out4=sprintf('\n      von Mises Stress = %8.4g psi',max(abs(vM)));
                    ss='psi';
                    sq=sprintf('Peak Response Values \n\n %s \n %s \n %s \n %s \n %s \n %s ',...
                                          out1,out2,out3,out4);                         
                else
                    out2=sprintf('     Velocity = %8.4g m/sec',max(abs(rv)));         
                    out3=sprintf(' Displacement = %8.4g mm',max(abs(rd)));
                    out4=sprintf('\n      von Mises Stress = %8.4g Pa',max(abs(vM)));
                    out5=sprintf('                       = %8.4g MPa',max(abs(vM))/(1.0e+06));        
                    ss='Pa';
                    sq=sprintf('Peak Response Values \n\n %s \n %s \n %s \n %s \n %s \n %s ',...
                                          out1,out2,out3,out4,out5);                  
                end
            %    
                disp(out1);
                disp(out2);
                disp(out3);
                disp(out4);
                
                if(app.iu==2)
                    disp(out5);
                end
            
                disp(' ');
                disp(' Overall Levels ');
                disp(' ');
                
                if(app.iu==1)
                    out1=sprintf(' sxx:  %8.4g psi rms ',std(sxx));
                    out2=sprintf(' syy:  %8.4g psi rms ',std(syy));     
                    out3=sprintf(' txy:  %8.4g psi rms ',std(txy));            
                else
                    out1=sprintf(' sxx:  %8.4g Pa rms ',std(syy));
                    out2=sprintf(' syy:  %8.4g Pa rms ',std(syy)); 
                    out3=sprintf(' txy:  %8.4g Pa rms ',std(txy));             
                end
                
                disp(out1);
                disp(out2);
                disp(out3);
                
                vel=max(abs(rv));
                
                
            % [Hunt_peak]=Hunt_plate_stress_shock(app.E,app.rho,app.mu,a,b,vel,ss);            
                   
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%
            
            [fig_num]=plate_stress_plots(fig_num,x,y,t,rd,rv,acc,sxx,syy,txy,vM,Tr,app.iu);
            
            %
            %
            t=fix_size(t);
            acc=fix_size(acc);
            rv=fix_size(rv);
            rd=fix_size(rd);
            vM=fix_size(vM);
            %
            acc=[t acc];
            rv=[t rv];
            rd=[t rd];
            vMs=[t vM];
            three_stress=[t sxx syy txy];
                  
            app.acceleration=acc;
            app.relative_velocity=rv;
            app.relative_displacement=rd;
            app.bending_stress=vMs;
            app.bending_stress_tensor=three_stress;
            
            app.ResultsTextArea_pft.Value=sq;         
            app.ResultsTextArea_pft.Visible='on';
            app.ResultsTextArea_Label_pft.Visible='on';

        end

        % Value changed function: ResponseLocationListBox_pressure
        function ResponseLocationListBox_pressureValueChanged(app, event)
            ClearResults(app);
        end

        % Button pushed function: CalculateButton_baseTrans
        function CalculateButton_baseTransPushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            fig_num=1;

            fmin=str2num(app.MinEditField_baseTrans.Value);
            fmax=str2num(app.MaxEditField_baseTrans.Value);
                             
            a=app.L;
            b=app.W;
            T=app.thick;

            damp=app.dm;

            x=str2num(app.xinEditField.Value); 
            y=str2num(app.yinEditField.Value);  
            
            if(isempty(x) || isempty(y))
                warndlg('Enter Response Locations coordinates');
                return;
            end    
            
            xo=x;
            yo=y;
            xc=0.5*app.L;
            yc=0.5*app.W;

            pax=x*pi/a;
            pby=y*pi/b;

            num_modes=1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            nf=20000;
               f(1)=fmin;
               for k=2:nf
                    f(k)=f(k-1)*2^(1/48);
                    if(f(k)>fmax)
                        break;
                    end    
               end
            nf=max(size(f));
            %
            
            if(app.nBC==1)  % SS
                num_modes=str2num(app.NumberofModesIncludedListBox_baseTrans.Value);
                fff=app.fbig(1:num_modes,:);
                app.fn=app.fbig(:,1);
                part=app.fbig(:,4);

                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]...
                    =ss_plate_trans_core_alt(nf,f,app.fn,damp,pax,pby,part,app.Amn,fff,a,b,T,app.E,app.mu,app.iu);

            end
            if(app.nBC==2)  % fixed-fixed-fixed-fixed
                pr=app.part_r;
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_fixed_fixed_fixed_Z(x,y,a,b,app.mu,app.ZAA,app.beta_r);
            end    
            if(app.nBC==3)  % fixed-free-fixed-free

                temp=x;
                x=y;
                y=temp;
                
                temp=xc;
                xc=yc;
                yc=temp;

                y=y-yc;
                pr=app.part_r;
               
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_free_fixed_free_Z(x,y,a,b,app.alpha_r,app.theta_r,app.beta,app.mu,app.ZAA,app.root);
  
            end
            if(app.nBC==4)  % corners
  
                x=xc-x;
                y=yc-y;

                pr=app.part_corners;

                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                               plate_corner_Z(x,y,a,b,app.alpha_r,app.beta_r,app.gamma_r,app.mu,app.ZAA);
   
            end   
            if(app.nBC~=1)
                [H,Hv,HA,Hsxx,Hsyy,Htxy]=plate_rectangular_frf(nf,num_modes,f,app.fn(1),damp(1),pr,Z,SXX,SYY,TXY);             
                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]=...
                        vibrationdata_plate_transfer_2(nf,app.iu,app.E,app.mu,T,H,Hv,HA,Hsxx,Hsyy,Htxy,f);  
            end    


            x=xo;
            y=yo;

            app.rel_disp_H=rd_trans;
            app.rel_vel_H=rv_trans;
            app.accel_H=accel_trans;
            app.H_vM=HM_stress_vM;
           
            HA=abs(HA);
            Hv=abs(Hv);
            H=abs(H);
            HM_stress_vM=abs(HM_stress_vM);    
                
            maxH=0;
            maxHv=0;
            maxHA=0;
            maxvM=0;
            %
                for k=1:nf
                    if(H(k)>maxH)
                        maxH=H(k);
                        maxF=f(k);
                    end
                    if(Hv(k)>maxHv)
                        maxHv=Hv(k);
                        maxFv=f(k);
                    end      
                    if(HA(k)>maxHA)
                        maxHA=HA(k);
                        maxFA=f(k);
                    end
                    if(HM_stress_vM(k)>maxvM)
                        maxvM=HM_stress_vM(k);
                        maxFvM=f(k);
                    end       
                end       
            %
            
            %%%%%%%%%
            
            md=6;
            
            x_label='Frequency (Hz)';
            
            f=fix_size(f);
            H=fix_size(H);
            Hv=fix_size(Hv);
            HA=fix_size(HA);
            
            %%%%%%%%%
            
            ppp=[f H];
            
            if(app.iu==1)
                t_string=sprintf('Rel Disp Frequency Response Function  x=%g in  y=%g in',x,y);
                y_label='Rel Disp (in)/ Base Accel (G) ';
            else
                t_string=sprintf('Rel Disp Frequency Response Function  x=%g m  y=%g m',x,y);
                y_label='Rel Disp (m)/ Base Accel (G) ';        
            end
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            %
            %%%%%%%%%%
            
            ppp=[f Hv];
            
            if(app.iu==1)
                t_string=sprintf('Rel Vel Frequency Response Function  x=%g in  y=%g in',x,y);
                y_label='Rel Vel (in/sec)/ Base Accel (G) ';
            else
                t_string=sprintf('Rel Vel Frequency Response Function  x=%g m  y=%g m',x,y);
                y_label='Rel Vel (m/sec)/ Base Accel (G) ';       
            end
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            %%%%%%%%
            %
            
            ppp=[f HA];
            
            y_label='Response Accel(G) / Base Accel(G)';
            
            if(app.iu==1)
                t_string=sprintf('Accel Frequency Response Function  x=%g in  y=%g in',x,y);
            else
                t_string=sprintf('Accel Frequency Response Function  x=%g m  y=%g m',x,y);     
            end
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            %%%%%%%%%%
            %    
            
            HA2=HA.*HA;
            
            ppp=[f HA2];
            
            y_label='Trans (G^2/G^2)';
            
            if(app.iu==1)
                t_string=sprintf('Accel Power Transmissibility  x=%g in  y=%g in',x,y);
            else
                t_string=sprintf('Accel Power Transmissibility  x=%g m  y=%g m',x,y);       
            end
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                
            %
            
            %%%%
                
                Hsxx=fix_size(Hsxx);
                Hsyy=fix_size(Hsyy);
                
                ppp1=[f Hsxx];
                ppp2=[f Hsyy];
                
                if(app.iu==1)
                    t_string=sprintf('Bending Stress  x=%g in  y=%g in',x,y);
                    y_label='Trans (psi/G)';
                else
                    t_string=sprintf('Bending Stress  x=%g m  y=%g m',x,y);
                    y_label='Trans (Pa/G)';        
                end   
                
                leg1='Sxx';
                leg2='Syy';
            
            try
                [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);        
            catch
            end

            %
            %%%%%
            
                if(app.iu==1)
                    t_string=sprintf('Shear Stress  x=%g in  y=%g in',x,y);
                    y_label='Trans (psi/G)';
                else
                    t_string=sprintf('Shear Stress  x=%g m  y=%g m',x,y);
                    y_label='Trans (Pa/G)';        
                end
             
            %    
            
            Htxy=fix_size(Htxy);
            ppp=[f Htxy];
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            %%%%%    
                
            HM_stress_vM=fix_size(HM_stress_vM);
            
            ppp=[f HM_stress_vM];
            
                if(app.iu==1)
                    t_string=sprintf('von Mises Stress  x=%g in  y=%g in',x,y);
                    y_label='Trans (psi/G)';
                else
                    t_string=sprintf('von Mises Stress  x=%g m  y=%g m',x,y);
                    y_label='Trans (Pa/G)';        
                end
            
            try
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            catch
            end

            %
            %%%%%%%
            %
                try
                    
                    disp(' ');
            %
                    if(app.iu==1)
                        out1=sprintf('  max Rel Disp FRF = %8.3g (in/G) at %8.4g Hz \n',maxH,maxF);
                        out2=sprintf(' max Rel Vel FRF = %8.3g (in/sec/G) at %8.4g Hz ',maxHv,maxFv);
                    else
                        out1=sprintf('  max Rel Disp FRF = %8.3g (m/G) at %8.4g Hz \n',maxH,maxF);
                        out2=sprintf(' max Rel Vel FRF = %8.3g (m/sec/G) at %8.4g Hz ',maxHv,maxFv);   
                    end
                    disp(out1);
                    disp(out2);

                    ss=sprintf('%s \n %s \n',out1,out2);
                
                catch
                end
            %   
            
                try
                    
                    out1=sprintf(' max Accel FRF    = %8.4g (G/G)     at %8.4g Hz ',maxHA,maxFA);
                    disp(out1);

                    ss=sprintf('%s \n %s \n',ss,out1);

                    disp(' ');
                    out1=sprintf(' max Power Trans  = %8.4g (G^2/G^2) at %8.4g Hz ',maxHA^2,maxFA);
                    disp(out1); 
                
                    ss=sprintf('%s \n %s \n',ss,out1);

                    disp(' ');
                    if(app.iu==1)
                        out1=sprintf(' max von Mises Stress FRF = %8.3g (psi/G) at %8.4g Hz ',maxvM,maxFvM);
                        disp(out1); 
                        sq=sprintf('%s \n %s \n',ss,out1);
                    else
                        out1=sprintf(' max von Mises Stress FRF = %8.3g (Pa/G) at %8.4g Hz ',maxvM,maxFvM);
                        out2=sprintf('                          = %8.3g (MPa/G) at %8.4g Hz ',maxvM/(1.0e+06),maxFvM);        
                        disp(out1); 
                        disp(out2); 
                        sq=sprintf('%s \n %s \n %s',ss,out1,out2);                
                    end
                    
                catch
                end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            try
                app.ResultsTextArea_FRF_baseTrans.Value=sq;
            catch
            end
            app.ResultsTextArea_FRF_baseTrans.Visible='on';
            app.ResultsTextArea_FRF_Label_baseTrans.Visible='on';
        end

        % Value changing function: MinEditField_baseTrans
        function MinEditField_baseTransValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: MaxEditField_baseTrans
        function MaxEditField_baseTransValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function ResponseLocationListBox_baseValueChanged(app, event)

            ClearResults(app);            
        end

        % Button pushed function: SaveButton_baseTrans
        function SaveButton_baseTransPushed(app, event)
            Items = char(app.FRFTypeListBox_baseTrans.Items);
            im=app.FRFTypeListBox_baseTrans.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel_H;
            end
            if(n==2)
                data=app.rel_vel_H;
            end
            if(n==3)
                data=app.rel_disp_H;
            end
            if(n==4)
                data=app.H_vM;
            end

            name=app.OutputArrayNameEditField_baseTrans.Value;

            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                wrndlg('Save failed')
            end    
        end

        % Button pushed function: CalculateButton_baseTrans_2
        function CalculateButton_baseTrans_2Pushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            fig_num=1;

            damp=app.dm;  % check damping in functions

            disp(' ');
            disp(' * * * * * * ');
            disp(' ');

            freq=str2num(app.FreqHzEditField.Value);
             Ain=str2num(app.AccelGEditField.Value);
          
            a=app.L;
            b=app.W;
            T=app.thick;
            
            x=str2num(app.xinEditField.Value); 
            y=str2num(app.yinEditField.Value);  

            if(isempty(x) || isempty(y))
                warndlg('Enter Response Locations coordinates');
                return;
            end    

            xo=x;
            yo=y;
            xc=0.5*app.L;
            yc=0.5*app.W;

            pax=x*pi/a;
            pby=y*pi/b;

            num_modes=1;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                nf=1;
                f(1)=freq;
            
            if(app.nBC==1)
                num_modes=str2num(app.NumberofModesIncludedListBox_baseTrans.Value);             
                fff=app.fbig(1:num_modes,:);
                app.fn=app.fbig(:,1);
                part=app.fbig(:,4);

                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]...
                    =ss_plate_trans_core_alt(nf,f,app.fn,damp,pax,pby,part,app.Amn,fff,a,b,T,app.E,app.mu,app.iu);     
            end
            if(app.nBC==2)  % fixed-fixed-fixed-fixed
                pr=app.part_r;
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_fixed_fixed_fixed_Z(x,y,a,b,app.mu,app.ZAA,app.beta_r);
            end    
            if(app.nBC==3)  % fixed-free-fixed-free

                temp=x;
                x=y;
                y=temp;
                
                temp=xc;
                xc=yc;
                yc=temp;

                y=y-yc;

                pr=app.part_r;
               
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_free_fixed_free_Z(x,y,a,b,app.alpha_r,app.theta_r,app.beta,app.mu,app.ZAA,app.root);

            end       
            if(app.nBC==4)

                x=xc-x;
                y=yc-y;

                pr=app.part_corners;

                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                               plate_corner_Z(x,y,a,b,app.alpha_r,app.beta_r,app.gamma_r,app.mu,app.ZAA);
 
            end   
            if(app.nBC~=1)
                [H,Hv,HA,Hsxx,Hsyy,Htxy]=plate_rectangular_frf(nf,num_modes,f,app.fn(1),damp(1),pr,Z,SXX,SYY,TXY);
                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]=...
                        vibrationdata_plate_transfer_2(nf,app.iu,app.E,app.mu,T,H,Hv,HA,Hsxx,Hsyy,Htxy,f);  
            end    
            x=xo;
            y=yo; 

               accel=Ain*abs(HA);
             rel_vel=Ain*abs(Hv);
            rel_disp=Ain*abs(H);
            vMstress=Ain*abs(HM_stress_vM);   
            %
            disp(' ');
            
            out1=sprintf(' Base Input:  %g Hz, %g G  \n\n',freq,Ain);
            out2=sprintf(' Response: \n');
            out3=sprintf('     Accel = %8.4g G \n',accel);

            ss=sprintf(' %s %s %s \n',out1,out2,out3);
            
            if(app.iu==1)
                out4=sprintf('   Rel Vel = %8.4g in/sec  \n',rel_vel);
                out5=sprintf('  Rel Disp = %8.4g in \n',rel_disp);
                out6=sprintf('  von Mises Stress = %8.4g psi',vMstress);
                ss=sprintf(' %s %s %s %s \n',ss,out4,out5,out6);
            else
                out4=sprintf('   Rel Vel = %8.4g m/sec  \n',rel_vel);
                out5=sprintf('  Rel Disp = %8.4g mm \n',rel_disp*1000);
                out6=sprintf('  von Mises Stress = %8.4g Pa',vMstress); 
                out7=sprintf('                   = %8.4g MPa',vMstress/(1.0e+06)); 
                ss=sprintf(' %s %s %s %s %s \n',ss,out4,out5,out6,out7);               
            end    
            
            disp(out1);
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            disp(out6);
            
            if(app.iu==2)
                disp(out7);
            end

            app.ResultsTextArea_baseTrans.Value=ss;
            app.ResultsTextArea_Label_baseTrans.Visible='on';
            app.ResultsTextArea_baseTrans.Visible='on';            

        end

        % Callback function
        function FreqHzEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Callback function
        function AccelGEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: FreqHzEditField
        function FreqHzEditFieldValueChanging2(app, event)
            ClearResults(app);
        end

        % Value changing function: AccelGEditField
        function AccelGEditFieldValueChanging2(app, event)
             ClearResults(app);
        end

        % Value changed function: NumberofModesIncludedListBox_baseTrans
        function NumberofModesIncludedListBox_baseTransValueChanged(app, event)
             ClearResults(app);
        end

        % Button pushed function: CalculateButton_10
        function CalculateButton_10Pushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end  

            try
                FS=app.SelectInputArrayListBox_psd.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input array failed');
                return;
            end

            fig_num=1;

            damp=app.dm;  % check damping in functions
          
            a=app.L;
            b=app.W;
            T=app.thick;
                   
            x=str2num(app.xinEditField.Value); 
            y=str2num(app.yinEditField.Value);  
                        
            if(isempty(x) || isempty(y))
                warndlg('Enter Response Locations coordinates');
                return;
            end    
            
            xo=x;
            yo=y;
            xc=0.5*app.L;
            yc=0.5*app.W;

            pax=x*pi/a;
            pby=y*pi/b;

            num_modes=1;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clear f;
            fmax=max(app.THM(:,1));
            nf=10000;
            n=length(app.fn);
            %
            disp(' ');
            %
            f=zeros(nf,1);
            f(1)=0.8*app.THM(1,1);
            for k=2:nf
                f(k)=f(k-1)*2^(1/48);
                if(f(k)>fmax)
                    break;
                end    
            end
            nf=max(size(f));
               
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    
            if(app.nBC==1) % SS
                num_modes=str2num(app.NumberofModesIncludedListBox_baseTrans.Value);      
                fff=app.fbig(1:num_modes,:);
                app.fn=app.fbig(:,1);
                part=app.fbig(:,4);
                        
                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]...
                        =ss_plate_trans_core_alt(nf,f,app.fn,damp,pax,pby,part,app.Amn,fff,a,b,T,app.E,app.mu,app.iu);
            end 
            if(app.nBC==2)  % fixed-fixed-fixed-fixed
                pr=app.part_r;
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_fixed_fixed_fixed_Z(x,y,a,b,app.mu,app.ZAA,app.beta_r);
            end             
            if(app.nBC==3)  % fixed-free-fixed-free

                temp=x;
                x=y;
                y=temp;
                
                temp=xc;
                xc=yc;
                yc=temp;

                y=y-yc;

                pr=app.part_r;
               
                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                        plate_fixed_free_fixed_free_Z(x,y,a,b,app.alpha_r,app.theta_r,app.beta,app.mu,app.ZAA,app.root);
            end   
            if(app.nBC==4) % corners           

                x=xc-x;
                y=yc-y;

                pr=app.part_corners;

                [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                               plate_corner_Z(x,y,a,b,app.alpha_r,app.beta_r,app.gamma_r,app.mu,app.ZAA);

            end    
            if(app.nBC~=1)
                [H,Hv,HA,Hsxx,Hsyy,Htxy]=plate_rectangular_frf(nf,num_modes,f,app.fn(1),damp(1),pr,Z,SXX,SYY,TXY);
                [accel_trans,rv_trans,rd_trans,vM_trans,HA,Hv,H,HM_stress_vM,Hsxx,Hsyy,Htxy]=...
                        vibrationdata_plate_transfer_2(nf,app.iu,app.E,app.mu,T,H,Hv,HA,Hsxx,Hsyy,Htxy,f);  
            end    

            x=xo;
            y=yo;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            PSD_in=app.THM;
            %
            
            [app.acc_response_psd]=psd_mult_trans(PSD_in,accel_trans);
            [app.rv_response_psd]=psd_mult_trans(PSD_in,rv_trans);
            [app.rd_response_psd]=psd_mult_trans(PSD_in,rd_trans);
            [app.vM_response_psd]=psd_mult_trans(PSD_in,vM_trans);
            
            %    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,accel_rms]=calculate_PSD_slopes( app.acc_response_psd(:,1),app.acc_response_psd(:,2)  );
            %
            [~,vel_rms]=calculate_PSD_slopes(  app.rv_response_psd(:,1),app.rv_response_psd(:,2)  );
            %
            [~,disp_rms]=calculate_PSD_slopes(  app.rd_response_psd(:,1),app.rd_response_psd(:,2)  );
            %
            [~,vM_rms]=calculate_PSD_slopes(  app.vM_response_psd(:,1),app.vM_response_psd(:,2)  );
            %
           
            freq=app.acc_response_psd(:,1);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
               dd='in';
               vv='in/sec';
               ss='psi';
            else
               dd='mm';
               vv='m/sec';
               ss='MPa';
               vM_rms=vM_rms/(1.0e+06);
            %
               disp_rms=disp_rms*1000;
            %
            end
            %
            disp(' ');
            disp('Acceleration Response ');
            out1=sprintf(' %8.4g GRMS',accel_rms);
            disp(out1);
            %
            disp(' ');
            disp('Relative Velocity Response ');
            out2=sprintf(' %8.4g %s RMS',vel_rms,vv);
            disp(out2);
            %
            disp(' ');
            disp(' Relative Displacement Response ');
            out3=sprintf(' %8.4g %s RMS',disp_rms,dd);
            disp(out3);
            %
            disp(' ');
            disp(' von Mises Response ');
            out4=sprintf(' %8.4g %s RMS',vM_rms,ss);
            disp(out4);
            %%

            sq=sprintf([' Acceleration Response \n %s ' ...
                ' \n\n Relative Velocity Response \n %s'  ...
                ' \n\n Relative Displacement Response \n %s'...
                ' \n\n von Mises Stress \n %s  \n'],out1,out2,out2,out4);
            
            
            % [Hunt_rms]=Hunt_plate_stress(app.E,app.rho,app.mu,a,b,vel_rms,ss);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            xlab=sprintf(' Frequency (Hz)');
            ylab_acc=sprintf('Accel (G^2/Hz)');
            %
            if(app.iu==1)
                ylab_rv=sprintf('Rel Vel (in/sec)^2/Hz)');
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
                ylab_ss=sprintf('Stress (psi^2/Hz)');    
            else
                ylab_rv=sprintf('Rel Vel (m/sec)^2/Hz)');    
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
                ylab_ss=sprintf('Stress (Pa^2/Hz)');      
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes( app.THM(:,1),app.THM(:,2) );

            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            ppp=[app.THM(:,1) app.THM(:,2)];
            qqq=[app.acc_response_psd(:,1) app.acc_response_psd(:,2)];

            if(app.iu==1)
                t_string=sprintf('Acceleration PSD x=%g in  y=%g in',x,y);
            else
                t_string=sprintf('Acceleration PSD x=%g m  y=%g m',x,y);               
            end    

            x_label=xlab;
            y_label=ylab_acc;
            rms_label='GRMS';
            leg1='Base Input'; 
            leg2='Response';

            [fig_num,h]=...
                plot_psd_two_function(fig_num,x_label,y_label,rms_label,t_string,ppp,qqq,leg1,leg2,fmin,fmax);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            s1=sprintf('Overall %6.3g %s RMS',vel_rms,vv);
            t_string=sprintf(' Relative Velocity Response PSD \n %s',s1);
            ppp=[app.rv_response_psd(:,1),app.rv_response_psd(:,2)];
            [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab_rv,t_string,ppp,fmin,fmax);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==2)  % convert to mm^2
                app.rd_response_psd(:,2)=app.rd_response_psd(:,2)*1.0e+06;
            end
            %
       
            ppp=[app.rd_response_psd(:,1),app.rd_response_psd(:,2)];
            s1=sprintf('Overall %6.3g %s RMS',disp_rms,dd);
            t_string=sprintf(' Relative Displacement Response PSD \n %s',s1);
            [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab_rd,t_string,ppp,fmin,fmax);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %
       
            ppp=[app.vM_response_psd(:,1),app.vM_response_psd(:,2)];
            s1=sprintf('Overall %6.3g %s RMS',vM_rms,ss);
            t_string=sprintf(' von Mises Stress PSD \n %s',s1);
            [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab_ss,t_string,ppp,fmin,fmax);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.ResultsTextArea_base_accel_psd.Value=sq;            
            app.ResultsTextArea_base_accel_psd.Visible='on';
            app.ResultsTextArea_base_accel_Label_psd.Visible='on';

        end

        % Button pushed function: UpdateListButton_3
        function UpdateListButton_3Pushed(app, event)
             update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
      
        end

        % Button pushed function: SaveButton_psd
        function SaveButton_psdPushed(app, event)
            
            Items = char(app.PSDTypeListBox.Items);
            im=app.PSDTypeListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.acc_response_psd;
            end
            if(n==2)
                data=app.rv_response_psd ;
            end
            if(n==3)
                data=app.rd_response_psd ;
            end
            if(n==4)
                data=app.vM_response_psd;
            end

            name=app.OutputArrayNameEditField_psd.Value;

            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                wrndlg('Save failed')
            end    
        
        end

        % Callback function
        function CalculateButton_11Pushed(app, event)
          
        end

        % Button pushed function: SaveButton_arb
        function SaveButton_arbPushed(app, event)
            
            Items = char(app.ResponseTypeListBox.Items);
            im=app.ResponseTypeListBox.Value;
            [n]=find_index(Items,im);            

            if(n==1)
                data=app.acceleration;
            end
            if(n==2)
                data=app.relative_velocity;
            end
            if(n==3)
                data=app.relative_displacement;
            end
            if(n==4)
                data=app.bending_stress;
            end
            if(n==5)
                data=app.bending_stress_tensor;
            end

            name=app.OutputArrayNameEditField_arb.Value;

            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                wrndlg('Save failed')
            end    

        end

        % Button pushed function: CalculateButton_arb_base
        function CalculateButton_arb_basePushed(app, event)
            preprocess(app);

            if(app.kflag==999)
                return;
            end

            fig_num=1;

            damp=app.dm;  % check damping in functions

            disp(' ');
            disp(' * * * * * * ');
            disp(' ');

            a=app.L;
            b=app.W;
            h=app.thick;
                 
            x=str2num(app.xinEditField.Value); 
            y=str2num(app.yinEditField.Value);  
            
            if(isempty(x) || isempty(y))
                warndlg('Enter Response Locations coordinates');
                return;
            end    

            xo=x;
            yo=y;
            xc=0.5*app.L;
            yc=0.5*app.W;

            pax=x*pi/a;
            pby=y*pi/b;

            num_modes=1;           
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
            if(app.nBC==1) % SS
                num_modes=str2num(app.NumberofModesIncludedListBox_baseTrans.Value);
                fff=app.fbig(1:num_modes,:);
                app.fn=app.fbig(:,1);
                PF=app.fbig(:,4);
                m_index=fff(:,6);
                n_index=fff(:,7);
            end
            if(app.nBC==2) % fixed-fixed-fixed-fixed
                PF=app.part_r;
            end                
            if(app.nBC==3) % fixed-free-fixed-free
                PF=app.part_r;
            end       
            if(app.nBC==4) % corners
                PF=app.part_corners;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            try
                FS=app.SelectInputArrayListBox_arb.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input array failed');
                return;
            end
            %
            t=app.THM(:,1);
            num_steps=length(t);
                    
            abase=app.THM(:,2);
              
            dt=mean(diff(t));
            %
            sr=1/dt;
            %
                disp(' ')
                disp(' Time Step ');
                dtmin=min(diff(t));
                dtmax=max(diff(t));
            %
                out4 = sprintf(' dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf(' dt     = %8.4g sec  ',dt);
                out6 = sprintf(' dtmax  = %8.4g sec  ',dtmax);
                disp(out4)
                disp(out5)
                disp(out6)
            %
                disp(' ')
                disp(' Sample Rate ');
                out4 = sprintf(' srmin  = %8.4g samples/sec  ',1/dtmax);
                out5 = sprintf(' sr     = %8.4g samples/sec  ',1/dt);
                out6 = sprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
                disp(out4)
                disp(out5)
                disp(out6)
            %
            if(((dtmax-dtmin)/dt)>0.01)
                disp(' ')
                disp(' Warning:  time step is not constant.')
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
                    
            rd=zeros(num_steps,1);
            rv=zeros(num_steps,1);
            ra=zeros(num_steps,1);
            %
            sxx=zeros(num_steps,1);
            syy=zeros(num_steps,1);
            txy=zeros(num_steps,1);
            %
            nrd=zeros(num_steps,num_modes);
            nrv=zeros(num_steps,num_modes);
            nra=zeros(num_steps,num_modes); 
            %
            [a1,a2,ra_b1,ra_b2,ra_b3,rv_b1,rv_b2,rv_b3,rd_b1,rd_b2,rd_b3]=...
                            srs_coefficients_avd(app.fn,damp,dt);
            %
            fmax=sr/10;
            %
            fprintf('\n maximum frequency limit for modal transient analysis: fmax=%9.5g Hz \n',fmax);
            %
            for j=1:num_modes
            %
                if(abs(PF(j))>0 && app.fn(j)<fmax)
            %
                        yp=-abase*PF(j);
            %
                        back   =[     1, -a1(j), -a2(j) ];
            %
            %   relative velocity
            %
                        clear forward;
                        forward=[ rv_b1(j),  rv_b2(j),  rv_b3(j) ];    
                        nrv(:,j)=filter(forward,back,yp);
            %
            %   relative displacement
            %
                        clear forward;
                        forward=[ rd_b1(j),  rd_b2(j),  rd_b3(j) ];      
                        nrd(:,j)=filter(forward,back,yp);
                        nbm(:,j)=nrd(:,j);
            %
            %   relative acceleraiton
            %
                        clear forward;  
                        forward=[ ra_b1(j),  ra_b2(j),  ra_b3(j) ];    
                        nra(:,j)=filter(forward,back,yp); 
            %
                    if(app.nBC==1)  % SS
                        ZZ=app.Amn*sin(m_index(j)*pax)*sin(n_index(j)*pby);                 
            %     
                         rd(:)= rd(:) +ZZ*nrd(:,j);
                         rv(:)= rv(:) +ZZ*nrv(:,j);             
                         ra(:)= ra(:) +ZZ*nra(:,j); 
            %
                         ZZxx=-(pi*m_index(j)/a)^2*ZZ;
                         ZZyy=-(pi*n_index(j)/b)^2*ZZ;
                         ZZxy= (pi^2/(a*b))*m_index(j)*n_index(j)*cos(m_index(j)*pax)*cos(n_index(j)*pby);
                         
                         sxx(:)=sxx(:)+ (ZZxx+app.mu*ZZyy)*nrd(:,j);
                         syy(:)=syy(:)+ (app.mu*ZZxx+ZZyy)*nrd(:,j);
                         txy(:)=txy(:)+ ZZxy*nrd(:,j);               
                    end
                    if(app.nBC==2)  % fixed-fixed-fixed-fixed
                        [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                            plate_fixed_fixed_fixed_fixed_Z(x,y,a,b,app.mu,app.ZAA,app.beta_r);                    
                    end                      
                    if(app.nBC==3)  % fixed-free-fixed-free
                        temp=x;
                        x=y;
                        y=temp;
                        
                        temp=xc;
                        xc=yc;
                        yc=temp;
        
                        y=y-yc;

                        [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                            plate_fixed_free_fixed_free_Z(x,y,a,b,...
                               app.alpha_r,app.theta_r,app.beta,app.mu,app.ZAA,app.root);
                         
                    end                     
                    if(app.nBC==4)  % corners
                        x=xc-x;
                        y=yc-y;            
                        [Z,d2Zdx2,d2Zdy2,d2Zdxdy,SXX,SYY,TXY]=...
                               plate_corner_Z(x,y,a,b,app.alpha_r,app.beta_r,app.gamma_r,app.mu,app.ZAA);
                    end    
                    if(app.nBC~=1)
                        ZZ=Z;

                        rd(:)= rd(:) +ZZ*nrd(:,j);
                        rv(:)= rv(:) +ZZ*nrv(:,j);             
                        ra(:)= ra(:) +ZZ*nra(:,j); 
%
                        sxx(:)=sxx(:)+ SXX*nrd(:,j);
                        syy(:)=syy(:)+ SYY*nrd(:,j);
                        txy(:)=txy(:)+ TXY*nrd(:,j);
                    end    
                end
            end
            
            x=xo;
            y=yo;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %       
            acc=ra+abase;
            %
            if(app.iu==1)
                    rd=rd*386;
                    rv=rv*386;
                    sxx=sxx*386;
                    syy=syy*386;
                    txy=txy*386;
            else
                    rd=rd*9.81*1000;
                    rv=rv*9.81; 
                    sxx=sxx*9.81;
                    syy=syy*9.81;
                    txy=txy*9.81;        
            end
            %
            z=h/2;
            term1=-(app.E*z/(1-app.mu^2));    
            term2=-(app.E*z/(1+app.mu));
            %
            sxx=sxx*term1;
            syy=syy*term1;
            txy=txy*term2;
            %
            clear length;
            n=length(sxx);
            vM=zeros(n,1);
            Tr=zeros(n,1);
                
            for i=1:n    
                [vM(i),Tr(i),~,~]=von_Mises_Tresca_plane_stress(sxx(i),syy(i),txy(i));  
            end
            %
            disp(' ');
            disp(' Peak Response Values ');
            disp(' ');
            out1=sprintf('          Acceleration = %8.4g G',max(abs(acc)));
            sq=sprintf('Peak Response Values \n\n %s \n ',out1);
            %    
            if(app.iu==1)
                    out2=sprintf('     Relative Velocity = %8.4g in/sec',max(abs(rv)));          
                    out3=sprintf(' Relative Displacement = %8.4g in',max(abs(rd)));  
                    out4=sprintf('\n      von Mises Stress = %8.4g psi',max(abs(vM)));
                       out5=sprintf('         Tresca Stress = %8.4g psi',max(abs(Tr)));       
                    ss='psi';
                    sq=sprintf('%s\n%s\n%s\n%s\n%s\n',sq,out2,out3,out4,out5);
            else
                    out2=sprintf('     Relative Velocity = %8.4g m/sec',max(abs(rv)));         
                    out3=sprintf(' Relative Displacement = %8.4g mm',max(abs(rd)));
                    out4=sprintf('\n      von Mises Stress = %8.4g Pa',max(abs(vM)));
                    out5=sprintf('                       = %8.4g MPa',max(abs(vM))/(1.0e+06));  
                       out6=sprintf('         Tresca Stress = %8.4g Pa',max(abs(Tr)));    
                       out7=sprintf('                       = %8.4g Pa',max(abs(Tr))/(1.0e+06));               
                    ss='Pa';
                    sq=sprintf('%s\n%s\n%s\n%s\n%s\n%s\n%s\n',sq,out2,out3,out4,out5,out6,out7);                 
            end
            %    
                disp(out1);
                disp(out2);
                disp(out3);
                disp(out4);
                disp(out5);
                
                if(app.iu==2)
                    disp(out6);
                    disp(out7);       
                end
                
                vel=max(abs(rv));
                
            
            % [Hunt_peak]=Hunt_plate_stress_shock(app.E,app.rho,app.mu,a,b,vel,ss);            
                
            %
            %
            t=fix_size(t);
            acc=fix_size(acc);
            rv=fix_size(rv);
            rd=fix_size(rd);
            vM=fix_size(vM);
            sxx=fix_size(sxx);
            syy=fix_size(syy);
            txy=fix_size(txy);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num)
            fig_num=fig_num+1;
            %
                plot(t,rd);
            %    
                if(app.iu==1)
                    out1=sprintf(' Relative Displacement at x=%g in  y=%g in',x,y);
                    ylabel('Rel Disp(in) ');
                else
                    out1=sprintf(' Relative Displacement at x=%g m  y=%g m',x,y);
                    ylabel('Rel Disp(m) ');
                end
                title(out1);
                xlabel('Time(sec)');
                grid on;
            %
                figure(fig_num)
                fig_num=fig_num+1;
            %
                plot(t,rv);
            %
                if(app.iu==1)
                    out1=sprintf(' Relative Velocity at x=%g in  y=%g in',x,y);        
                    ylabel('Rel Vel (in/sec) ');
                else
                    out1=sprintf(' Relative Velocity at x=%g m  y=%g m',x,y);        
                    ylabel('Rel Vel (m/sec) ');
                end
                title(out1);    
                xlabel('Time (sec)');
                grid on    
            %
                figure(fig_num)
                fig_num=fig_num+1;
            %
                plot(t,abase);
            %
                out1=sprintf(' Base Input Acceleration ');
                title(out1);
                ylabel('Accel(G) ');
                xlabel('Time(sec)'); 
                grid on;
            %
                figure(fig_num)
                fig_num=fig_num+1;
            %
                plot(t,acc);
            %
                if(app.iu==1)
                    out1=sprintf(' Response Acceleration at x=%g in  y=%g in',x,y);
                else
                    out1=sprintf(' Response Acceleration at x=%g m  y=%g m',x,y);       
                end
                title(out1);
                ylabel('Accel(G) ');
                xlabel('Time(sec)'); 
                grid on;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                xlabel3='Time (sec)';
                
                if(app.iu==1)
                    ylabel1='Stress (psi)';
                    ylabel2='Stress (psi)';
                    ylabel3='Stress (psi)';
                else
                    ylabel1='Stress (Pa)';
                    ylabel2='Stress (Pa)';
                    ylabel3='Stress (Pa)';     
                end
                
                data1=[t sxx];
                data2=[t syy];
                data3=[t txy];
                
                
                if(app.iu==1)
                    t_string1=sprintf(' Sxx Stress at x=%g in  y=%g in',x,y);        
                    t_string2=sprintf(' Syy Stress at x=%g in  y=%g in',x,y);      
                    t_string3=sprintf(' Txy Stress at x=%g in  y=%g in',x,y);              
                else
                    t_string1=sprintf(' Sxx Stress at x=%g m  y=%g m',x,y);        
                    t_string2=sprintf(' Syy Stress at x=%g m  y=%g m',x,y);      
                    t_string3=sprintf(' Txy Stress at x=%g m  y=%g m',x,y);        
                end
                           
                [fig_num]=subplots_three_linlin_three_titles_ss(fig_num,xlabel3,ylabel1,ylabel2,ylabel3,data1,data2,data3,t_string1,t_string2,t_string3);
                
                xlabel2='Time (sec)';
                
                if(app.iu==1)
                    ylabel1='Stress (psi)';
                    ylabel2='Stress (psi)';
                else
                    ylabel1='Stress (Pa)';
                    ylabel2='Stress (Pa)';
                end
                
                data1=[t vM];
                data2=[t Tr];
            
                if(app.iu==1)
                    t_string1=sprintf('von Mises Stress at x=%g in  y=%g in',x,y);        
                    t_string2=sprintf('Tresca Stress at x=%g in  y=%g in',x,y);      
                else
                    t_string1=sprintf('von Mises Stress at x=%g m  y=%g m',x,y);        
                    t_string2=sprintf('Tresca Stress at x=%g m  y=%g m',x,y);           
                end    
                       
                [fig_num]=subplots_two_linlin_two_titles_xzero(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                [prho]=Pearson_coefficient(sxx,syy);
                
                if(prho>=0 && prho<=1)
            
                    figure(fig_num)
                    fig_num=fig_num+1;
                    plot(sxx,syy,'b.','MarkerSize',1);
                    if(app.iu==1)
                        tstring=sprintf('Bending Stress Scatter Plot, Response at x=%g in  y=%g in \n Pearson Coefficient= %6.3g',x,y,prho);
                        ylabel('Syy (psi) ');
                        xlabel('Sxx (psi) ');
                    else
                        tstring=sprintf('Bending Stress Scatter Plot, Response at x=%g m  y=%g m \n Pearson Coefficient= %6.3g',x,y,prho);     
                        ylabel('Syy (Pa) ');
                        xlabel('Sxx (Pa) ');        
                    end
                    title(tstring);
                    grid on;
                
                    [xx]=get(gca,'xlim');
                    [yy]=get(gca,'ylim');
            
                    Qmax=max([ abs(xx) abs(yy)  ]);
            
                    [Qmax]=ytick_linear_scale(Qmax);
            
                    xlim([-Qmax,Qmax]);
                    ylim([-Qmax,Qmax]);
            
                    axis square;    
                
                end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            acc=[t acc];
            rv=[t rv];
            rd=[t rd];
            vMs=[t vM];
            stensor=[t sxx syy txy];
                  
            app.acceleration=acc;
            app.relative_velocity=rv;
            app.relative_displacement=rd;
            app.bending_stress=vMs;
            app.bending_stress_tensor=stensor;

            app.ResultsTextArea_arbit.Value=sq;
            app.ResultsTextArea_arbit.Visible='on';
            app.ResultsTextArea_arbit_Label.Visible='on';
            
        end

        % Button pushed function: UpdateListButton_arb
        function UpdateListButton_arbPushed(app, event)
            update_arb(app);
        end

        % Value changed function: listbox_method_arb
        function listbox_method_arbValueChanged(app, event)

            Items = char(app.listbox_method_arb.Items);
            im=app.listbox_method_arb.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_arb(app);
               app.SelectInputArrayListBox_arb.Value=name;
            end    
        end

        % Value changed function: BCsListBox
        function BCsListBoxValueChanged(app, event)

            Unit(app);
            app.jflag=1;
        end

        % Button down function: AppliedForceTab
        function AppliedForceTabButtonDown(app, event)
            if(app.nBC~=1)
                warndlg('Applied Force is unavailable for selected BC');
                app.TabGroup2.SelectedTab=app.TabGroup2.Children(1); 
            end    
        end

        % Button down function: BaseExcitationTab
        function BaseExcitationTabButtonDown(app, event)
            % if(app.nBC~=1)
            %    warndlg('Base Excitation is unavailable for selected BC');
            %    app.TabGroup2.SelectedTab=app.TabGroup2.Children(1); 
            % end    
        end

        % Button pushed function: SettoCenterButton
        function SettoCenterButtonPushed(app, event)
            app.yinEditField.Value=sprintf('%8.4g',app.W/2);    
            app.xinEditField.Value=sprintf('%8.4g',app.L/2);            
        end

        % Button pushed function: SettoFundamentalFrequencyButton
        function SettoFundamentalFrequencyButtonPushed(app, event)
            app.FreqHzEditField.Value=sprintf('%8.4g',app.fn(1));
        end

        % Button pushed function: CalculatefnButton_fixed_point
        function CalculatefnButton_fixed_pointPushed(app, event)
            app.jflag=2;

            ClearResults(app);

            corners(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RectangularPlateBendingUIFigure = uifigure('Visible', 'off');
            app.RectangularPlateBendingUIFigure.Name = 'Rectangular Plate Bending';
            app.ReturnButton = uibutton(app.RectangularPlateBendingUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.RectangularPlateBendingUIFigure);
            app.MaterialPropertiesTab = uitab(app.TabGroup);
            app.MaterialPropertiesTab.Title = 'Material Properties';
            app.MaterialLabel = uilabel(app.MaterialPropertiesTab);
            app.MaterialLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialPropertiesTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialPropertiesTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialPropertiesTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialPropertiesTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialPropertiesTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.UnitsListBoxLabel = uilabel(app.MaterialPropertiesTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.MaterialPropertiesTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.PoissonRatioEditFieldLabel = uilabel(app.MaterialPropertiesTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.MaterialPropertiesTab, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.MaterialPropertiesTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = 'Uniform Nonstructural Mass (lbm)';
            app.UniformNonstructuralMasslbmEditField = uieditfield(app.MaterialPropertiesTab, 'text');
            app.UniformNonstructuralMasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @UniformNonstructuralMasslbmEditFieldValueChanging, true);
            app.UniformNonstructuralMasslbmEditField.Value = '0';
            app.CommonBCsTab = uitab(app.TabGroup);
            app.CommonBCsTab.Title = 'Common BCs';
            app.Image = uiimage(app.CommonBCsTab);
            app.Image.ImageSource = 'plate_image.jpg';
            app.LeftBCListBoxLabel = uilabel(app.CommonBCsTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.CommonBCsTab);
            app.LeftBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.CommonBCsTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.CommonBCsTab);
            app.RightBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Fixed';
            app.TopBCListBoxLabel = uilabel(app.CommonBCsTab);
            app.TopBCListBoxLabel.Text = 'Top BC';
            app.TopBCListBox = uilistbox(app.CommonBCsTab);
            app.TopBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.TopBCListBox.ValueChangedFcn = createCallbackFcn(app, @TopBCListBoxValueChanged, true);
            app.TopBCListBox.Value = 'Fixed';
            app.BottomBCListBoxLabel = uilabel(app.CommonBCsTab);
            app.BottomBCListBoxLabel.Text = 'Bottom BC';
            app.BottomBCListBox = uilistbox(app.CommonBCsTab);
            app.BottomBCListBox.Items = {'Fixed', 'Pinned', 'Free'};
            app.BottomBCListBox.ValueChangedFcn = createCallbackFcn(app, @BottomBCListBoxValueChanged, true);
            app.BottomBCListBox.Value = 'Fixed';
            app.CalculatefnButton = uibutton(app.CommonBCsTab, 'push');
            app.CalculatefnButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatefnButtonPushed, true);
            app.CalculatefnButton.Text = 'Calculate fn';
            app.ResultsTextAreaLabel = uilabel(app.CommonBCsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.CommonBCsTab);
            app.ThicknessinEditFieldLabel = uilabel(app.CommonBCsTab);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.CommonBCsTab, 'text');
            app.SideWidthinEditFieldLabel = uilabel(app.CommonBCsTab);
            app.SideWidthinEditFieldLabel.Text = 'Side Width (in)';
            app.SideWidthinEditField = uieditfield(app.CommonBCsTab, 'text');
            app.BottomLengthinEditFieldLabel = uilabel(app.CommonBCsTab);
            app.BottomLengthinEditFieldLabel.Text = 'Bottom Length (in)';
            app.BottomLengthinEditField = uieditfield(app.CommonBCsTab, 'text');
            app.FixedPointatEachCornerTab = uitab(app.TabGroup);
            app.FixedPointatEachCornerTab.Title = 'Fixed Point at Each Corner';
            app.CalculatefnButton_fixed_point = uibutton(app.FixedPointatEachCornerTab, 'push');
            app.CalculatefnButton_fixed_point.ButtonPushedFcn = createCallbackFcn(app, @CalculatefnButton_fixed_pointPushed, true);
            app.CalculatefnButton_fixed_point.Text = 'Calculate fn';
            app.ThicknessinEditField_Label_fixed_point = uilabel(app.FixedPointatEachCornerTab);
            app.ThicknessinEditField_Label_fixed_point.Text = 'Thickness (in)';
            app.ThicknessinEditField_fixed_point = uieditfield(app.FixedPointatEachCornerTab, 'text');
            app.SideWidthinEditField_Label_fixed_point = uilabel(app.FixedPointatEachCornerTab);
            app.SideWidthinEditField_Label_fixed_point.Text = 'Side Width (in)';
            app.SideWidthinEditField_fixed_point = uieditfield(app.FixedPointatEachCornerTab, 'text');
            app.BottomLengthinEditField_Label_fixed_point = uilabel(app.FixedPointatEachCornerTab);
            app.BottomLengthinEditField_Label_fixed_point.Text = 'Bottom Length (in)';
            app.BottomLengthinEditField_fixed_point = uieditfield(app.FixedPointatEachCornerTab, 'text');
            app.ResultsTextArea_2Label = uilabel(app.FixedPointatEachCornerTab);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.ResultsTextArea_fixed_point = uitextarea(app.FixedPointatEachCornerTab);
            app.ResultsTextArea_fixed_point.Editable = 'off';
            app.OtherBCCasesTab = uitab(app.TabGroup);
            app.OtherBCCasesTab.Title = 'Other BC Cases';
            app.TabGroup2 = uitabgroup(app.OtherBCCasesTab);
            app.NaturalFrequencyTab = uitab(app.TabGroup2);
            app.NaturalFrequencyTab.Title = 'Natural Frequency';
            app.UITable_fn = uitable(app.NaturalFrequencyTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'; 'm'; 'n'; 'Participation Factor'; 'Modal Mass Ratio'};
            app.UITable_fn.RowName = {};
            app.ThicknessinEditField_SSLabel = uilabel(app.NaturalFrequencyTab);
            app.ThicknessinEditField_SSLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField_SS = uieditfield(app.NaturalFrequencyTab, 'text');
            app.ThicknessinEditField_SS.ValueChangingFcn = createCallbackFcn(app, @ThicknessinEditField_SSValueChanging, true);
            app.SideWidthinEditField_SSLabel = uilabel(app.NaturalFrequencyTab);
            app.SideWidthinEditField_SSLabel.Text = 'Side Width (in)';
            app.SideWidthinEditField_SS = uieditfield(app.NaturalFrequencyTab, 'text');
            app.SideWidthinEditField_SS.ValueChangingFcn = createCallbackFcn(app, @SideWidthinEditField_SSValueChanging, true);
            app.BottomLengthinEditField_SSLabel = uilabel(app.NaturalFrequencyTab);
            app.BottomLengthinEditField_SSLabel.Text = 'Bottom Length (in)';
            app.BottomLengthinEditField_SS = uieditfield(app.NaturalFrequencyTab, 'text');
            app.BottomLengthinEditField_SS.ValueChangingFcn = createCallbackFcn(app, @BottomLengthinEditField_SSValueChanging, true);
            app.CalculatefnButton_SS = uibutton(app.NaturalFrequencyTab, 'push');
            app.CalculatefnButton_SS.ButtonPushedFcn = createCallbackFcn(app, @CalculatefnButton_SSPushed, true);
            app.CalculatefnButton_SS.Text = 'Calculate fn';
            app.DampingTab = uitab(app.TabGroup2);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UniformQEditFieldLabel = uilabel(app.DampingTab);
            app.UniformQEditFieldLabel.Text = 'Uniform Q';
            app.UniformQEditField = uieditfield(app.DampingTab, 'text');
            app.AppliedForceTab = uitab(app.TabGroup2);
            app.AppliedForceTab.Title = 'Applied Force';
            app.AppliedForceTab.ButtonDownFcn = createCallbackFcn(app, @AppliedForceTabButtonDown, true);
            app.TabGroup4 = uitabgroup(app.AppliedForceTab);
            app.PointForceFRFTab = uitab(app.TabGroup4);
            app.PointForceFRFTab.Title = 'Point Force FRF';
            app.Label_2 = uilabel(app.PointForceFRFTab);
            app.Label_2.Text = 'This script calculates the frequency response function for a plate subjected to a point force.';
            app.TabGroup5 = uitabgroup(app.PointForceFRFTab);
            app.CalculateResponseTab = uitab(app.TabGroup5);
            app.CalculateResponseTab.Title = 'Calculate Response';
            app.CalculateButton = uibutton(app.CalculateResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ForceInputLocationLabel = uilabel(app.CalculateResponseTab);
            app.ForceInputLocationLabel.Text = 'Force Input Location';
            app.FrequencyHzLimitsLabel = uilabel(app.CalculateResponseTab);
            app.FrequencyHzLimitsLabel.Text = 'Frequency (Hz) Limits';
            app.Label_3 = uilabel(app.CalculateResponseTab);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.CalculateResponseTab);
            app.Label_4.Text = '%';
            app.MinEditFieldLabel = uilabel(app.CalculateResponseTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculateResponseTab, 'text');
            app.MinEditField.ValueChangingFcn = createCallbackFcn(app, @MinEditFieldValueChanging, true);
            app.MaxEditFieldLabel = uilabel(app.CalculateResponseTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculateResponseTab, 'text');
            app.MaxEditField.ValueChangingFcn = createCallbackFcn(app, @MaxEditFieldValueChanging, true);
            app.AlongLengthEditFieldLabel = uilabel(app.CalculateResponseTab);
            app.AlongLengthEditFieldLabel.Text = 'Along Length';
            app.AlongLengthEditField = uieditfield(app.CalculateResponseTab, 'text');
            app.AlongLengthEditField.ValueChangingFcn = createCallbackFcn(app, @AlongLengthEditFieldValueChanging, true);
            app.AlongLengthEditField.Value = '50';
            app.AlongWidthEditFieldLabel = uilabel(app.CalculateResponseTab);
            app.AlongWidthEditFieldLabel.Text = 'Along Width';
            app.AlongWidthEditField = uieditfield(app.CalculateResponseTab, 'text');
            app.AlongWidthEditField.ValueChangingFcn = createCallbackFcn(app, @AlongWidthEditFieldValueChanging, true);
            app.AlongWidthEditField.Value = '50';
            app.ResponseLocationListBoxLabel = uilabel(app.CalculateResponseTab);
            app.ResponseLocationListBoxLabel.Text = 'Response Location';
            app.ResponseLocationListBox = uilistbox(app.CalculateResponseTab);
            app.ResponseLocationListBox.Items = {'Center', 'Force Input Location', 'Spatial Average'};
            app.ResponseLocationListBox.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationListBoxValueChanged, true);
            app.ResponseLocationListBox.Value = 'Center';
            app.ResultsTab = uitab(app.TabGroup5);
            app.ResultsTab.Title = 'Results';
            app.ResultsTextArea_point_force_FRF_Label = uilabel(app.ResultsTab);
            app.ResultsTextArea_point_force_FRF_Label.Text = 'Results';
            app.ResultsTextArea_point_force_FRF = uitextarea(app.ResultsTab);
            app.SaveTab = uitab(app.TabGroup5);
            app.SaveTab.Title = 'Save';
            app.FRFTypeListBoxLabel = uilabel(app.SaveTab);
            app.FRFTypeListBoxLabel.Text = 'FRF Type';
            app.FRFTypeListBox = uilistbox(app.SaveTab);
            app.FRFTypeListBox.Items = {'Receptance  ', 'Mobility    ', 'Accelerance '};
            app.FRFTypeListBox.Value = 'Receptance  ';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveTab, 'text');
            app.ForceTransientTab = uitab(app.TabGroup4);
            app.ForceTransientTab.Title = 'Force Transient';
            app.TabGroup5_2 = uitabgroup(app.ForceTransientTab);
            app.ReadArrayTab = uitab(app.TabGroup5_2);
            app.ReadArrayTab.Title = 'Read Array';
            app.listbox_method = uilistbox(app.ReadArrayTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ReadArrayTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ReadArrayTab, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.ForceLabel = uilabel(app.ReadArrayTab);
            app.ForceLabel.Text = {' The input array must have two columns: '; ' time (sec) & force or pressure'};
            app.SelectInputArrayListBoxLabel = uilabel(app.ReadArrayTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ReadArrayTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.UpdateListButton_2 = uibutton(app.ReadArrayTab, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.PointForceResponseTab = uitab(app.TabGroup5_2);
            app.PointForceResponseTab.Title = 'Point Force Response';
            app.CalculateButton_pft = uibutton(app.PointForceResponseTab, 'push');
            app.CalculateButton_pft.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_pftPushed, true);
            app.CalculateButton_pft.Text = 'Calculate';
            app.ForceInputLocationLabel_2 = uilabel(app.PointForceResponseTab);
            app.ForceInputLocationLabel_2.Text = 'Force Input Location';
            app.Label_5 = uilabel(app.PointForceResponseTab);
            app.Label_5.Text = '%';
            app.Label_6 = uilabel(app.PointForceResponseTab);
            app.Label_6.Text = '%';
            app.ResponseLocationListBox_Label_pft = uilabel(app.PointForceResponseTab);
            app.ResponseLocationListBox_Label_pft.Text = 'Response Location';
            app.ResponseLocationListBox_pft = uilistbox(app.PointForceResponseTab);
            app.ResponseLocationListBox_pft.Items = {'Center', 'Half Length & Quarter Width', 'Quarter Length & Half Width', 'Quarter Length & Quarter Width', 'Force Input Location'};
            app.ResponseLocationListBox_pft.Value = 'Center';
            app.AlongLengthEditField_2Label = uilabel(app.PointForceResponseTab);
            app.AlongLengthEditField_2Label.Text = 'Along Length';
            app.AlongLengthEditField_2 = uieditfield(app.PointForceResponseTab, 'text');
            app.AlongLengthEditField_2.Value = '50';
            app.AlongWidthEditField_2Label = uilabel(app.PointForceResponseTab);
            app.AlongWidthEditField_2Label.Text = 'Along Width';
            app.AlongWidthEditField_2 = uieditfield(app.PointForceResponseTab, 'text');
            app.AlongWidthEditField_2.Value = '50';
            app.UniformPressureResponseTab = uitab(app.TabGroup5_2);
            app.UniformPressureResponseTab.Title = 'Uniform Pressure Response';
            app.ResponseLocationListBox_Label_pft_2 = uilabel(app.UniformPressureResponseTab);
            app.ResponseLocationListBox_Label_pft_2.Text = 'Response Location';
            app.ResponseLocationListBox_pressure = uilistbox(app.UniformPressureResponseTab);
            app.ResponseLocationListBox_pressure.Items = {'Center', 'Half Length & Quarter Width', 'Quarter Length & Half Width', 'Quarter Length & Quarter Width'};
            app.ResponseLocationListBox_pressure.ValueChangedFcn = createCallbackFcn(app, @ResponseLocationListBox_pressureValueChanged, true);
            app.ResponseLocationListBox_pressure.Value = 'Center';
            app.CalculateButton_pressure = uibutton(app.UniformPressureResponseTab, 'push');
            app.CalculateButton_pressure.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_pressurePushed, true);
            app.CalculateButton_pressure.Text = 'Calculate';
            app.ResultsTab_pft = uitab(app.TabGroup5_2);
            app.ResultsTab_pft.Title = 'Results';
            app.ResultsTextArea_Label_pft = uilabel(app.ResultsTab_pft);
            app.ResultsTextArea_Label_pft.Text = 'Results';
            app.ResultsTextArea_pft = uitextarea(app.ResultsTab_pft);
            app.SaveTab_pft = uitab(app.TabGroup5_2);
            app.SaveTab_pft.Title = 'Save';
            app.TimeHistoryListBoxLabel = uilabel(app.SaveTab_pft);
            app.TimeHistoryListBoxLabel.Text = 'Time History';
            app.TimeHistoryListBox = uilistbox(app.SaveTab_pft);
            app.TimeHistoryListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'von Mises Bending Stress', 'Stress:  Time, Sxx, Syy, Txy'};
            app.TimeHistoryListBox.Value = 'Acceleration';
            app.SaveButton_pft = uibutton(app.SaveTab_pft, 'push');
            app.SaveButton_pft.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_pftPushed, true);
            app.SaveButton_pft.Text = 'Save';
            app.OutputArrayNameEditField_2Label = uilabel(app.SaveTab_pft);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_pft = uieditfield(app.SaveTab_pft, 'text');
            app.NumberofModesIncludedListBoxLabel = uilabel(app.AppliedForceTab);
            app.NumberofModesIncludedListBoxLabel.Text = {'Number of Modes '; 'Included'};
            app.NumberofModesIncludedListBox = uilistbox(app.AppliedForceTab);
            app.NumberofModesIncludedListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModesIncludedListBox.Value = '1';
            app.BaseExcitationTab = uitab(app.TabGroup2);
            app.BaseExcitationTab.Title = 'Base Excitation';
            app.BaseExcitationTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationTabButtonDown, true);
            app.TabGroup3 = uitabgroup(app.BaseExcitationTab);
            app.TransmissibilityTab = uitab(app.TabGroup3);
            app.TransmissibilityTab.Title = 'Transmissibility';
            app.TabGroup5_3 = uitabgroup(app.TransmissibilityTab);
            app.CalculateResponseTab_2 = uitab(app.TabGroup5_3);
            app.CalculateResponseTab_2.Title = 'Calculate Response';
            app.CalculateButton_baseTrans = uibutton(app.CalculateResponseTab_2, 'push');
            app.CalculateButton_baseTrans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_baseTransPushed, true);
            app.CalculateButton_baseTrans.Text = 'Calculate';
            app.FrequencyHzLimitsLabel_2 = uilabel(app.CalculateResponseTab_2);
            app.FrequencyHzLimitsLabel_2.Text = 'Frequency (Hz) Limits';
            app.MinEditField_2Label = uilabel(app.CalculateResponseTab_2);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_baseTrans = uieditfield(app.CalculateResponseTab_2, 'text');
            app.MinEditField_baseTrans.ValueChangingFcn = createCallbackFcn(app, @MinEditField_baseTransValueChanging, true);
            app.MaxEditField_2Label = uilabel(app.CalculateResponseTab_2);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_baseTrans = uieditfield(app.CalculateResponseTab_2, 'text');
            app.MaxEditField_baseTrans.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_baseTransValueChanging, true);
            app.ResultsTab_2 = uitab(app.TabGroup5_3);
            app.ResultsTab_2.Title = 'Results';
            app.ResultsTextArea_FRF_Label_baseTrans = uilabel(app.ResultsTab_2);
            app.ResultsTextArea_FRF_Label_baseTrans.Text = 'Results';
            app.ResultsTextArea_FRF_baseTrans = uitextarea(app.ResultsTab_2);
            app.SaveTab_baseTrans = uitab(app.TabGroup5_3);
            app.SaveTab_baseTrans.Title = 'Save';
            app.FRFTypeListBox_Label_baseTrans = uilabel(app.SaveTab_baseTrans);
            app.FRFTypeListBox_Label_baseTrans.Text = 'FRF Type';
            app.FRFTypeListBox_baseTrans = uilistbox(app.SaveTab_baseTrans);
            app.FRFTypeListBox_baseTrans.Items = {'Absolute Acceleration', 'Relative Velocity', 'Relative Displacement', 'von Mises Stress'};
            app.FRFTypeListBox_baseTrans.Value = 'Absolute Acceleration';
            app.SaveButton_baseTrans = uibutton(app.SaveTab_baseTrans, 'push');
            app.SaveButton_baseTrans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_baseTransPushed, true);
            app.SaveButton_baseTrans.Text = 'Save';
            app.OutputArrayNameEditFieldLabel_baseTrans = uilabel(app.SaveTab_baseTrans);
            app.OutputArrayNameEditFieldLabel_baseTrans.Text = 'Output Array Name';
            app.OutputArrayNameEditField_baseTrans = uieditfield(app.SaveTab_baseTrans, 'text');
            app.SineInputTab = uitab(app.TabGroup3);
            app.SineInputTab.Title = 'Sine Input';
            app.CalculateButton_baseTrans_2 = uibutton(app.SineInputTab, 'push');
            app.CalculateButton_baseTrans_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_baseTrans_2Pushed, true);
            app.CalculateButton_baseTrans_2.Text = 'Calculate';
            app.ResultsTextArea_Label_baseTrans = uilabel(app.SineInputTab);
            app.ResultsTextArea_Label_baseTrans.Text = 'Results';
            app.ResultsTextArea_baseTrans = uitextarea(app.SineInputTab);
            app.FreqHzEditFieldLabel = uilabel(app.SineInputTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SineInputTab, 'text');
            app.FreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanging2, true);
            app.AccelGEditFieldLabel = uilabel(app.SineInputTab);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.SineInputTab, 'text');
            app.AccelGEditField.ValueChangingFcn = createCallbackFcn(app, @AccelGEditFieldValueChanging2, true);
            app.AccelGEditField.Value = '1';
            app.SettoFundamentalFrequencyButton = uibutton(app.SineInputTab, 'push');
            app.SettoFundamentalFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFundamentalFrequencyButtonPushed, true);
            app.SettoFundamentalFrequencyButton.Text = 'Set to Fundamental Frequency';
            app.PSDInputTab = uitab(app.TabGroup3);
            app.PSDInputTab.Title = 'PSD Input';
            app.TabGroup5_4 = uitabgroup(app.PSDInputTab);
            app.CalculateResponseTab_3 = uitab(app.TabGroup5_4);
            app.CalculateResponseTab_3.Title = 'Calculate Response';
            app.CalculateButton_10 = uibutton(app.CalculateResponseTab_3, 'push');
            app.CalculateButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_10Pushed, true);
            app.CalculateButton_10.Text = 'Calculate';
            app.listbox_method_psd = uilistbox(app.CalculateResponseTab_3);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.CalculateResponseTab_3);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.UpdateListButton_3 = uibutton(app.CalculateResponseTab_3, 'push');
            app.UpdateListButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_3Pushed, true);
            app.UpdateListButton_3.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.CalculateResponseTab_3);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.CalculateResponseTab_3);
            app.SelectInputArrayListBox_psd.Items = {};
            app.SelectInputArrayListBox_psd.Value = {};
            app.ResultsTab_3 = uitab(app.TabGroup5_4);
            app.ResultsTab_3.Title = 'Results';
            app.ResultsTextArea_base_accel_Label_psd = uilabel(app.ResultsTab_3);
            app.ResultsTextArea_base_accel_Label_psd.Text = 'Results';
            app.ResultsTextArea_base_accel_psd = uitextarea(app.ResultsTab_3);
            app.SaveTab_3 = uitab(app.TabGroup5_4);
            app.SaveTab_3.Title = 'Save';
            app.PSDTypeListBoxLabel = uilabel(app.SaveTab_3);
            app.PSDTypeListBoxLabel.Text = 'PSD Type';
            app.PSDTypeListBox = uilistbox(app.SaveTab_3);
            app.PSDTypeListBox.Items = {'Absolute Acceleration', 'Relative Velocity', 'Relative Displacement', 'von Mises Stress'};
            app.PSDTypeListBox.Value = 'Absolute Acceleration';
            app.SaveButton_psd = uibutton(app.SaveTab_3, 'push');
            app.SaveButton_psd.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_psdPushed, true);
            app.SaveButton_psd.Text = 'Save';
            app.OutputArrayNameEditField_3Label = uilabel(app.SaveTab_3);
            app.OutputArrayNameEditField_3Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_psd = uieditfield(app.SaveTab_3, 'text');
            app.ArbitraryInputTab = uitab(app.TabGroup3);
            app.ArbitraryInputTab.Title = 'Arbitrary Input';
            app.TabGroup5_5 = uitabgroup(app.ArbitraryInputTab);
            app.ReadArrayTab_2 = uitab(app.TabGroup5_5);
            app.ReadArrayTab_2.Title = 'Read Array';
            app.listbox_method_arb = uilistbox(app.ReadArrayTab_2);
            app.listbox_method_arb.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_arb.ValueChangedFcn = createCallbackFcn(app, @listbox_method_arbValueChanged, true);
            app.listbox_method_arb.Tag = 'listbox_method';
            app.listbox_method_arb.Value = 'Array Preloaded in Matlab';
            app.text1_arb = uilabel(app.ReadArrayTab_2);
            app.text1_arb.Tag = 'text1';
            app.text1_arb.Text = 'Select Data Input Method';
            app.ForceLabel_arb = uilabel(app.ReadArrayTab_2);
            app.ForceLabel_arb.Text = {' The input array must have two columns: '; ' time (sec) & accel (G)'};
            app.UpdateListButton_arb = uibutton(app.ReadArrayTab_2, 'push');
            app.UpdateListButton_arb.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_arbPushed, true);
            app.UpdateListButton_arb.Text = 'Update List';
            app.SelectInputArrayListBox_Label_arb = uilabel(app.ReadArrayTab_2);
            app.SelectInputArrayListBox_Label_arb.Text = 'Select Input Array';
            app.SelectInputArrayListBox_arb = uilistbox(app.ReadArrayTab_2);
            app.SelectInputArrayListBox_arb.Items = {'Item 1'};
            app.CalculateButton_arb_base = uibutton(app.ReadArrayTab_2, 'push');
            app.CalculateButton_arb_base.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_arb_basePushed, true);
            app.CalculateButton_arb_base.Text = 'Calculate';
            app.ResultsTab_4 = uitab(app.TabGroup5_5);
            app.ResultsTab_4.Title = 'Results';
            app.ResultsTextArea_arbit_Label = uilabel(app.ResultsTab_4);
            app.ResultsTextArea_arbit_Label.Text = 'Results';
            app.ResultsTextArea_arbit = uitextarea(app.ResultsTab_4);
            app.SaveTab_arb = uitab(app.TabGroup5_5);
            app.SaveTab_arb.Title = 'Save';
            app.ResponseTypeListBoxLabel = uilabel(app.SaveTab_arb);
            app.ResponseTypeListBoxLabel.Text = 'Response Type';
            app.ResponseTypeListBox = uilistbox(app.SaveTab_arb);
            app.ResponseTypeListBox.Items = {'Acceleration', 'Relative Velocity', 'Relative Displacement', 'von Mises Bending Stress', 'Stress:  Time, Sxx, Syy, Txy'};
            app.ResponseTypeListBox.Value = 'Acceleration';
            app.SaveButton_arb = uibutton(app.SaveTab_arb, 'push');
            app.SaveButton_arb.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_arbPushed, true);
            app.SaveButton_arb.Text = 'Save';
            app.OutputArrayNameEditField_4Label = uilabel(app.SaveTab_arb);
            app.OutputArrayNameEditField_4Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_arb = uieditfield(app.SaveTab_arb, 'text');
            app.NumberofModesIncludedListBox_Label_baseTrans = uilabel(app.BaseExcitationTab);
            app.NumberofModesIncludedListBox_Label_baseTrans.Text = {'Number of Modes '; 'Included'};
            app.NumberofModesIncludedListBox_baseTrans = uilistbox(app.BaseExcitationTab);
            app.NumberofModesIncludedListBox_baseTrans.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModesIncludedListBox_baseTrans.ValueChangedFcn = createCallbackFcn(app, @NumberofModesIncludedListBox_baseTransValueChanged, true);
            app.NumberofModesIncludedListBox_baseTrans.Value = '1';
            app.ResponseLocationReferencedtoLowerLeftCornerLabel = uilabel(app.BaseExcitationTab);
            app.ResponseLocationReferencedtoLowerLeftCornerLabel.Text = {'Response Location Referenced '; 'to Lower Left Corner'};
            app.xinEditFieldLabel = uilabel(app.BaseExcitationTab);
            app.xinEditFieldLabel.Text = 'x (in)';
            app.xinEditField = uieditfield(app.BaseExcitationTab, 'text');
            app.yinEditFieldLabel = uilabel(app.BaseExcitationTab);
            app.yinEditFieldLabel.Text = 'y (in)';
            app.yinEditField = uieditfield(app.BaseExcitationTab, 'text');
            app.SettoCenterButton = uibutton(app.BaseExcitationTab, 'push');
            app.SettoCenterButton.ButtonPushedFcn = createCallbackFcn(app, @SettoCenterButtonPushed, true);
            app.SettoCenterButton.Text = 'Set to Center';
            app.BCsListBoxLabel = uilabel(app.OtherBCCasesTab);
            app.BCsListBoxLabel.Text = 'BCs';
            app.BCsListBox = uilistbox(app.OtherBCCasesTab);
            app.BCsListBox.Items = {'Simply-Supported All Edges', 'Fixed All Edges', 'Fixed-Free-Fixed-Free'};
            app.BCsListBox.ValueChangedFcn = createCallbackFcn(app, @BCsListBoxValueChanged, true);
            app.BCsListBox.Value = 'Simply-Supported All Edges';
            app.rectangular_plate_bending_aver10byTomIrvineLabel = uilabel(app.RectangularPlateBendingUIFigure);
            app.rectangular_plate_bending_aver10byTomIrvineLabel.Text = {'rectangular_plate_bending_a   ver 1.2  by Tom Irvine            Natural frequencies & modes shape of a rectangular plate'; ''};
            app.ContextMenu = uicontextmenu(app.RectangularPlateBendingUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CalculatefnButton_fixed_point.ContextMenu = app.ContextMenu;
            app.CalculatefnButton_SS.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.RectangularPlateBendingUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.CalculateButton_baseTrans.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.RectangularPlateBendingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rectangular_plate_bending_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RectangularPlateBendingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RectangularPlateBendingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RectangularPlateBendingUIFigure)
        end
    end
end