classdef rectangular_plate_uniform_pressure_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RectangularPlateUniformPressureUIFigure  matlab.ui.Figure
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        EnterDataTab                    matlab.ui.container.Tab
        ResponseLocationLabel           matlab.ui.control.Label
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditFieldLabel                 matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.EditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.EditField
        WidthEditFieldLabel             matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.EditField
        LengthEditFieldLabel            matlab.ui.control.Label
        BoundaryConditionsListBox       matlab.ui.control.ListBox
        BoundaryConditionsListBoxLabel  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        MaterialTab                     matlab.ui.container.Tab
        UniformNonstructuralMassEditField  matlab.ui.control.EditField
        UniformNonstructuralMassEditFieldLabel  matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensityEditField            matlab.ui.control.EditField
        MassDensityEditFieldLabel       matlab.ui.control.Label
        ElasticModulusEditField         matlab.ui.control.EditField
        ElasticModulusEditFieldLabel    matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        CalculatefnTransTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CalculateTab_2                  matlab.ui.container.Tab
        MaxFreqHzEditField              matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel         matlab.ui.control.Label
        MinFreqHzEditField              matlab.ui.control.EditField
        MinFreqHzEditFieldLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        NaturalFrequenciesTextArea      matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        ApplyPressurePSDTab             matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        CalculateTab_PSD                matlab.ui.container.Tab
        UpdateListButton                matlab.ui.control.Button
        SelectPSDInputArrayListBox      matlab.ui.control.ListBox
        SelectPSDInputArrayListBoxLabel  matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateButton_PSD             matlab.ui.control.Button
        InterpolationifneededListBox    matlab.ui.control.ListBox
        InterpolationifneededListBoxLabel  matlab.ui.control.Label
        ExportTab_2                     matlab.ui.container.Tab
        OutputPSDArrayNameEditField     matlab.ui.control.EditField
        OutputPSDArrayNameEditFieldLabel  matlab.ui.control.Label
        DirlikFatigueButton             matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        PSDFunctionListBox              matlab.ui.control.ListBox
        PSDFunctionListBoxLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        iu                     % Description
        imat
        displacement_transfer
        displacement_power_transfer
        stress_transfer
        stress_power_transfer
        disp_transfer 
        disp_power_trans
        von_Mises_stress_transfer 
        von_Mises_stress_power_transfer
        E
        rho
        fmin
        fmax

        vM_power_trans            
        Hxx_power_trans
        Hyy_power_trans
        Hxy_power_trans
 
        disp_psd
        stress_psd    

        x
        y
 
    end
    
    methods (Access = private)

        function update(app)
            [cell_array]=update_power_spectral_density();
            app.SelectPSDInputArrayListBox.Items=unique(sort(cell_array));
            try
                app.listbox_method.Value=app.SelectPSDInputArrayListBox.Items{1};
            catch
            end
        end

        function Change(app)

            app.NaturalFrequenciesTextArea.Value='';

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.LengthEditFieldLabel.Text='Length (in)';
                app.WidthEditFieldLabel.Text='Width (in)';
                app.ThicknessEditFieldLabel.Text='Thickness (in)';
                app.XEditFieldLabel.Text='X (in)';
                app.YEditFieldLabel.Text='Y (in)';      
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (psi)';
                app.MassDensityEditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.UniformNonstructuralMassEditFieldLabel.Text='Nonstructural Mass (lbm)';
            else
                app.LengthEditFieldLabel.Text='Length (m)';
                app.WidthEditFieldLabel.Text='Width (m)';
                app.ThicknessEditFieldLabel.Text='Thickness (mm)';
                app.XEditFieldLabel.Text='X (m)';
                app.YEditFieldLabel.Text='Y (m)';
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (GPa)';
                app.MassDensityEditFieldLabel.Text='Mass Density (kg/m^3)';
                app.UniformNonstructuralMassEditFieldLabel.Text='Nonstructural Mass (kg)';               
            end   


            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,poisson]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModulusEditField.Value=sprintf('%g',app.E);
            app.MassDensityEditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensityEditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (psi)';   
                app.UniformNonstructuralMassEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
            else
                app.MassDensityEditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModulusEditFieldLabel.Text='Elastic Modulus (GPa)';   
                app.UniformNonstructuralMassEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';              
            end    

            app.PoissonRatioEditField.Value=sprintf('%g',poisson);

        end    

        function ClosePlots(app)

            app.NaturalFrequenciesTextArea.Value='';

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            Change(app);
            update(app);
            app.DirlikFatigueButton.Enable='off';
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ClosePlots(app);
            Change(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);
            app.DirlikFatigueButton.Enable='off';
            
            Items = strtrim(app.BoundaryConditionsListBox.Items);
            im = strtrim(app.BoundaryConditionsListBox.Value);
            n_BC = find(strcmp(Items, im));

            a=str2num(app.LengthEditField.Value);
            b=str2num(app.WidthEditField.Value);
            h=str2num(app.ThicknessEditField.Value);
            Q=str2num(app.AmplificationFactorQEditField.Value);
            app.x=str2num(app.XEditField.Value);
            app.y=str2num(app.YEditField.Value);

            app.fmin=str2num(app.MinFreqHzEditField.Value);
            app.fmax=str2num(app.MaxFreqHzEditField.Value);            

            app.E=str2num(app.ElasticModulusEditField.Value);
            app.rho=str2num(app.MassDensityEditField.Value);
            mu=str2num(app.PoissonRatioEditField.Value);
            NSM=str2num(app.UniformNonstructuralMassEditField.Value);

            damp=1/(2*Q);
            
            if(app.iu==1)
                app.rho=app.rho/386;
                NSM=NSM/386;
            else
                [app.E]=GPa_to_Pa(app.E);
                h=h/1000;
            end
            %
            area=a*b;
            vol=area*h;
            %
            app.rho=app.rho+(NSM/vol);
            %
            total_mass=app.rho*vol;
            mass_per_area=total_mass/area;
            %
            D=app.E*h^3/(12*(1-mu^2));
            
            fig_num=1;
            
            if(n_BC==1)
            %    
                [app.disp_transfer,app.von_Mises_stress_transfer,f,HM_stress_xx,HM_stress_yy,HM_stress_xy,fbig]=...
                ss_ss_ss_ss_plate_uniform_alta(app.E,D,app.rho,h,a,b,mu,mass_per_area,damp,app.fmin,app.fmax,fig_num,app.iu,app.x,app.y);    
           
                mass=mass_per_area*a*b;

                ss='    fn(Hz)   m    n        PF         EMM       EMM ratio    Eig at (x,y)';

                sz=size(fbig);

                for i=1:sz(1)
                
                    f5=fbig(i,5);
                    if(abs(f5)<(mass/1000))
                        f5=0;
                    end
                    f6=fbig(i,6);
                    if(abs(f6)<(max(abs(fbig(:,6))/1000)))
                        f6=0;
                    end

                    out1=sprintf(' %5.1f \t %d\t %d\t %6.3f\t %6.3f\t %5.3f\t %8.4e  ',fbig(i,1),fbig(i,2),fbig(i,3),fbig(i,4),f5,f5/mass,f6);
                    ss=sprintf('%s\n%s',ss,out1);
                end    
                
            %
            else
            %
                [fn,zzr,fig_num,P,dPdx,d2Pdx2,W,dWdy,d2Wdy2,norm,PF]=...
                fixed_fixed_fixed_fixed_plate_Rayleigh_alta(D,app.rho,h,a,b,mu,mass_per_area,fig_num,app.iu);
            %
                [app.disp_transfer,app.von_Mises_stress_transfer,f,HM_stress_xx,HM_stress_yy,HM_stress_xy]=...
                fixed_fixed_fixed_fixed_plate_pressure_alta(app.E,app.rho,mu,a,b,h,...
                fn,fig_num,P,dPdx,d2Pdx2,W,dWdy,d2Wdy2,norm,PF,damp,app.x,app.y,app.fmin,app.fmax,app.iu); 
            %
                ss='    fn(Hz)';
                ss=sprintf('%s\n%5.1f',ss,fn);                
            %
            end
          
            app.NaturalFrequenciesTextArea.Value=ss;

            try
                
                clear length;
                num=length(f);
                HM_disp_2=zeros(num,1);
                HM_stress_vM2=zeros(num,1);
                HM_stress_xx2=zeros(num,1);
                HM_stress_yy2=zeros(num,1);
                HM_stress_xy2=zeros(num,1);
            %
                for i=1:num
                    HM_disp_2(i)=app.disp_transfer(i,2)^2;
                    HM_stress_vM2(i)=app.von_Mises_stress_transfer(i,2)^2;
                    HM_stress_xx2(i)=HM_stress_xx(i)^2;
                    HM_stress_yy2(i)=HM_stress_yy(i)^2;
                    HM_stress_xy2(i)=HM_stress_xy(i)^2;  
                end    

                app.von_Mises_stress_power_transfer=[f HM_stress_vM2];
                app.disp_power_trans=[f HM_disp_2];    
                
                app.vM_power_trans=[f HM_stress_vM2]; 
                app.Hxx_power_trans=[f HM_stress_xx2]; 
                app.Hyy_power_trans=[f HM_stress_yy2]; 
                app.Hxy_power_trans=[f HM_stress_xy2];
            
            catch
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = strtrim(app.FunctionListBox.Items);
            im = strtrim(app.FunctionListBox.Value);
            n = find(strcmp(Items, im));
            
            if(n==1)
                data=app.disp_transfer;
            end  
            if(n==2)
                data=app.disp_power_trans;
            end
            if(n==3)
                data=app.von_Mises_stress_transfer;
            end  
            if(n==4)
                data=app.von_Mises_stress_power_transfer;
            end
            
            sz=size(data);
            
            if(max(sz)==0)
                msgbox('Data size is zero ');    
            else
                output_name=app.OutputArrayNameEditField.Value;
                assignin('base', output_name,data);
                msgbox('Export Complete.  Press Return. ');
            end
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
        
            Change(app);
        end

        % Value changed function: BoundaryConditionsListBox
        function BoundaryConditionsListBoxValueChanged(app, event)
      
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
           
            
        end

        % Value changing function: LengthEditField
        function LengthEditFieldValueChanging(app, event)
       
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: WidthEditField
        function WidthEditFieldValueChanging(app, event)
         
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: ThicknessEditField
        function ThicknessEditFieldValueChanging(app, event)
            
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: ElasticModulusEditField
        function ElasticModulusEditFieldValueChanging(app, event)
        
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: MassDensityEditField
        function MassDensityEditFieldValueChanging(app, event)
         
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
           
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Value changing function: UniformNonstructuralMassEditField
        function UniformNonstructuralMassEditFieldValueChanging(app, event)
            
            app.NaturalFrequenciesTextArea.Value='';
        end

        % Button pushed function: DirlikFatigueButton
        function DirlikFatigueButtonPushed(app, event)
            stress_psd_fatigue_nasgro_a;
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            
            Items = strtrim(app.PSDFunctionListBox.Items);
            im = strtrim(app.PSDFunctionListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.disp_psd;
            else
                data=app.stress_psd;    
            end

            try
                output_name=strtrim(app.OutputPSDArrayNameEditField.Value);
            
                assignin('base', output_name, data);

                app.DirlikFatigueButton.Enable='on';
            
                msgbox('Save Complete');
            catch
                msgbox('Save Failed');
            end
        end

        % Button pushed function: CalculateButton_PSD
        function CalculateButton_PSDPushed(app, event)

            ClosePlots(app);

            fig_num=10;

            Items = strtrim(app.InterpolationifneededListBox.Items);
            im = strtrim(app.InterpolationifneededListBox.Value);
            nni = find(strcmp(Items, im));

            FS=app.SelectPSDInputArrayListBox.Value;
            
            try
                THM=evalin('base',FS);  
            catch
                warndlg('Input array not found');
                return;
            end
            
            fb=THM(:,1);
            b=THM(:,2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fa=app.disp_power_trans(:,1);
            a=app.disp_power_trans(:,2);
            
            [ppp,rms]=power_transmissibility_mult_function(fa,a,fb,b,nni);
            
            if(app.iu==1)
                t_string=sprintf('Displacement Response PSD  (x=%g, y=%g in) \n %7.4g in rms overall',app.x,app.y,rms);
                y_label='Disp (in^2/Hz)';
                fprintf('\n  Overall displacement = %8.4g inch 3-sigma\n',3*rms);
            else
                t_string=sprintf('Displacement Response PSD  (x=%g, y=%g m) \n %7.4g mm rms overall',app.x,app.y,rms);   
                y_label='Disp (mm^2/Hz)'; 
                fprintf('\n  Overall displacement = %8.4g mm 3-sigma\n',3*rms);    
            end
                
            x_label='Frequency (Hz)';
            
            md=7;
            
            fc=ppp(:,1);
            
            app.fmin=fc(1);
            app.fmax=fc(end);
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);
            
            app.disp_psd=ppp;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fa=app.Hxx_power_trans(:,1);
            ax=app.Hxx_power_trans(:,2);
            ay=app.Hyy_power_trans(:,2);
            
            [pppx,rmsx]=power_transmissibility_mult_function(fa,ax,fb,b,nni);
            [pppy,rmsy]=power_transmissibility_mult_function(fa,ay,fb,b,nni);
            
            if(app.iu==1)
                t_string=sprintf('Stress Response PSD  (x=%g, y=%g in)',app.x,app.y);
                y_label='Stress (psi^2/Hz)';
                uu='psi';
            else
                t_string=sprintf('Stress Response PSD  (x=%g, y=%g m)',app.x,app.y);   
                y_label='Stress (Pa^2/Hz)';
                uu='Pa ';
            end
            
            ppp1=pppx;
            ppp2=pppy;
            
            leg1=sprintf('SXX %7.3g %s RMS',rmsx,uu);
            leg2=sprintf('SYY %7.3g %s RMS',rmsy,uu);
            
            md=7;
            
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fa=app.vM_power_trans(:,1);
            a=app.vM_power_trans(:,2);
            
            [ppp,rms]=power_transmissibility_mult_function(fa,a,fb,b,nni);
            
            if(app.iu==1)
                t_string=sprintf('von Mises Stress Response PSD  (x=%g, y=%g in) \n %7.4g psi rms overall',app.x,app.y,rms);
                y_label='Stress (psi^2/Hz)';
            else
                t_string=sprintf('von Mises Stress Response PSD  (x=%g, y=%g m) \n %7.4g Pa rms overall',app.x,app.y,rms);   
                y_label='Stress (Pa^2/Hz)';    
            end
                
            x_label='Frequency (Hz)';
            
            md=7;
            
            fc=ppp(:,1);
            
            app.fmin=fc(1);
            app.fmax=fc(end);
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);
            
            app.stress_psd=ppp;
           
            app.DirlikFatigueButton.Enable='off';

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               try
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
           
            disp('save');
            
            try
                Items = strtrim(app.UnitsListBox.Items);
                im = strtrim(app.UnitsListBox.Value);
                app.iu = find(strcmp(Items, im));
                plate_pressure.iu=app.iu;
            catch
            end
            
            try
			    Items = strtrim(app.BoundaryConditionsListBox.Items);
                im = strtrim(app.BoundaryConditionsListBox.Value);
                n_BC = find(strcmp(Items, im));
                plate_pressure.BC=n_BC;
            catch
            end
            
            try
			    Items = char(app.MaterialListBox.Items);
                im=app.MaterialListBox.Value;
                [app.imat]=find_index(Items,im);     
                plate_pressure.mat=app.imat;
            catch
            end
            try
                L=(app.LengthEditField.Value);
                plate_pressure.L=L;
            catch
            end
            try
                width=(app.WidthEditField.Value);
                plate_pressure.width=width;
            catch
            end
            try
                thickness=(app.ThicknessEditField.Value);
                plate_pressure.thickness=thickness;
            catch
            end
            try
                X=(app.XEditField.Value);
                plate_pressure.X=X;
            catch
            end
            try
                Y=(app.YEditField.Value);
                plate_pressure.Y=Y;
            catch
            end
            try
                Q=(app.AmplificationFactorQEditField.Value);
                plate_pressure.Q=Q;
            catch
            end
            try
                MINF=(app.MinFreqHzEditField.Value);
                plate_pressure.MINF=MINF;
            catch
            end            
            try
                MAXF=(app.MaxFreqHzEditField.Value);
                plate_pressure.MAXF=MAXF;
            catch
            end
            try
                elastic_modulus=(app.ElasticModulusEditField.Value);
                plate_pressure.elastic_modulus=elastic_modulus;
            catch
            end
            try
                poisson_ratio=(app.PoissonRatioEditField.Value);
                plate_pressure.poisson_ratio=poisson_ratio;
            catch
            end
            try
                app.rho=(app.MassDensityEditField.Value);
                plate_pressure.rho=app.rho;
            catch
            end
            try
                NSM=(app.UniformNonstructuralMassEditField.Value);
                plate_pressure.NSM=NSM;
            catch
            end
            
            % % %
             
             structnames = fieldnames(plate_pressure, '-full'); % fields in the struct
              
            % % %
             
                [writefname, writepname] = uiputfile('*.mat','Save data as');
               
                elk=sprintf('%s%s',writepname,writefname);
            
            try
                save(elk, 'plate_pressure'); 
            catch
                warndlg('Save error');
                return;
            end
             
            disp(' ');
            out1=sprintf('Save Complete: %s',writefname);
            disp(out1);
            msgbox(out1);
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select plot save file');

            NAME = [pathname,filename];
            
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
            
            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
               plate_pressure=evalin('base','plate_pressure');
            catch
               warndlg(' evalin failed ');
               setappdata(0,'eflag',1);   
               return;
            end
            
            %%%%%


            try
                app.UnitsListBox.Items.Value=app.UnitsListBox.Items{app.iu};
            catch
            end
            
            try
                app.BoundaryConditionsListBox.Items=app.BoundaryConditionsListBox.Items{plate_pressure.BC};
            catch
            end
            
            try
                app.MaterialListBox.Items=app.MaterialListBox.Items{plate_pressure.mat};
            catch
            end
            try
                app.LengthEditField.Value=plate_pressure.L;
            catch
            end
            try
                app.WidthEditField.Value=plate_pressure.width;
            catch
            end
            try
                app.ThicknessEditField.Value=plate_pressure.thickness;
            catch
            end
            try
                app.XEditField.Value=plate_pressure.X;
            catch
            end
            try
                app.YEditField.Value=plate_pressure.Y;
            catch
            end
            try
                app.AmplificationFactorQEditField.Value=plate_pressure.Q;
            catch
            end
            try
                app.MinFreqHzEditField.Value=plate_pressure.MINF;
            catch
            end            
            try
                app.MaxFreqHzEditField.Value=plate_pressure.MAXF;
            catch
            end
            try
                app.ElasticModulusEditField.Value=plate_pressure.elastic_modulus;
            catch
            end
            try
                app.PoissonRatioEditField.Value=plate_pressure.poisson_ratio;
            catch
            end
            try
                app.MassDensityEditField.Value=plate_pressure.rho;
            catch
            end
            try
                app.UniformNonstructuralMassEditField.Value=plate_pressure.NSM;
            catch
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RectangularPlateUniformPressureUIFigure = uifigure('Visible', 'off');
            app.RectangularPlateUniformPressureUIFigure.Name = 'Rectangular Plate Uniform Pressure';
            app.ReturnButton = uibutton(app.RectangularPlateUniformPressureUIFigure, 'push');
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.RectangularPlateUniformPressureUIFigure);
            app.Label.Text = {'rectangular_plate_uniform_pressure_a   ver 1.0   by Tom Irvine'; ''; ' Baffled plate bending mode response to an applied uniform acoustic pressure.'; ''};
            app.TabGroup = uitabgroup(app.RectangularPlateUniformPressureUIFigure);
            app.EnterDataTab = uitab(app.TabGroup);
            app.EnterDataTab.Title = 'Enter Data';
            app.UnitsListBoxLabel = uilabel(app.EnterDataTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.EnterDataTab);
            app.UnitsListBox.Items = {'English', 'Metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.BoundaryConditionsListBoxLabel = uilabel(app.EnterDataTab);
            app.BoundaryConditionsListBoxLabel.Text = 'Boundary Conditions';
            app.BoundaryConditionsListBox = uilistbox(app.EnterDataTab);
            app.BoundaryConditionsListBox.Items = {'Simply-Supported, All Edges', 'Fixed, All Edges'};
            app.BoundaryConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsListBoxValueChanged, true);
            app.BoundaryConditionsListBox.Value = 'Simply-Supported, All Edges';
            app.LengthEditFieldLabel = uilabel(app.EnterDataTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.EnterDataTab, 'text');
            app.LengthEditField.ValueChangingFcn = createCallbackFcn(app, @LengthEditFieldValueChanging, true);
            app.WidthEditFieldLabel = uilabel(app.EnterDataTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.EnterDataTab, 'text');
            app.WidthEditField.ValueChangingFcn = createCallbackFcn(app, @WidthEditFieldValueChanging, true);
            app.ThicknessEditFieldLabel = uilabel(app.EnterDataTab);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.EnterDataTab, 'text');
            app.ThicknessEditField.ValueChangingFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanging, true);
            app.XEditFieldLabel = uilabel(app.EnterDataTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EnterDataTab, 'text');
            app.YEditFieldLabel = uilabel(app.EnterDataTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EnterDataTab, 'text');
            app.AmplificationFactorQEditFieldLabel = uilabel(app.EnterDataTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.EnterDataTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.ResponseLocationLabel = uilabel(app.EnterDataTab);
            app.ResponseLocationLabel.Text = 'Response Location';
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.MaterialListBoxLabel = uilabel(app.MaterialTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModulusEditFieldLabel = uilabel(app.MaterialTab);
            app.ElasticModulusEditFieldLabel.Text = 'Elastic Modulus';
            app.ElasticModulusEditField = uieditfield(app.MaterialTab, 'text');
            app.ElasticModulusEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModulusEditFieldValueChanging, true);
            app.MassDensityEditFieldLabel = uilabel(app.MaterialTab);
            app.MassDensityEditFieldLabel.Text = 'Mass Density';
            app.MassDensityEditField = uieditfield(app.MaterialTab, 'text');
            app.MassDensityEditField.ValueChangingFcn = createCallbackFcn(app, @MassDensityEditFieldValueChanging, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.MaterialTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.MaterialTab, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.UniformNonstructuralMassEditFieldLabel = uilabel(app.MaterialTab);
            app.UniformNonstructuralMassEditFieldLabel.Text = 'Uniform Nonstructural Mass';
            app.UniformNonstructuralMassEditField = uieditfield(app.MaterialTab, 'text');
            app.UniformNonstructuralMassEditField.ValueChangingFcn = createCallbackFcn(app, @UniformNonstructuralMassEditFieldValueChanging, true);
            app.UniformNonstructuralMassEditField.Value = '0';
            app.CalculatefnTransTab = uitab(app.TabGroup);
            app.CalculatefnTransTab.Title = 'Calculate fn & Trans';
            app.TabGroup2 = uitabgroup(app.CalculatefnTransTab);
            app.CalculateTab_2 = uitab(app.TabGroup2);
            app.CalculateTab_2.Title = 'Calculate';
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.CalculateTab_2);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.CalculateTab_2);
            app.CalculateButton = uibutton(app.CalculateTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MinFreqHzEditFieldLabel = uilabel(app.CalculateTab_2);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.CalculateTab_2, 'text');
            app.MaxFreqHzEditFieldLabel = uilabel(app.CalculateTab_2);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.CalculateTab_2, 'text');
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.FunctionListBoxLabel = uilabel(app.ExportTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTab);
            app.FunctionListBox.Items = {'Displacement Trans', 'Displacement Power Trans', 'von Mises Stress Trans', 'von Mises Stress Power Trans'};
            app.FunctionListBox.Value = 'Displacement Trans';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.ApplyPressurePSDTab = uitab(app.TabGroup);
            app.ApplyPressurePSDTab.Title = 'Apply Pressure PSD';
            app.TabGroup3 = uitabgroup(app.ApplyPressurePSDTab);
            app.CalculateTab_PSD = uitab(app.TabGroup3);
            app.CalculateTab_PSD.Title = 'Calculate';
            app.InterpolationifneededListBoxLabel = uilabel(app.CalculateTab_PSD);
            app.InterpolationifneededListBoxLabel.Text = 'Interpolation if needed';
            app.InterpolationifneededListBox = uilistbox(app.CalculateTab_PSD);
            app.InterpolationifneededListBox.Items = {'Linear', 'Logarithmic'};
            app.InterpolationifneededListBox.Value = 'Logarithmic';
            app.CalculateButton_PSD = uibutton(app.CalculateTab_PSD, 'push');
            app.CalculateButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PSDPushed, true);
            app.CalculateButton_PSD.Text = 'Calculate';
            app.listbox_method = uilistbox(app.CalculateTab_PSD);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab_PSD);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.SelectPSDInputArrayListBoxLabel = uilabel(app.CalculateTab_PSD);
            app.SelectPSDInputArrayListBoxLabel.Text = 'Select PSD Input Array';
            app.SelectPSDInputArrayListBox = uilistbox(app.CalculateTab_PSD);
            app.SelectPSDInputArrayListBox.Items = {'Item 1'};
            app.UpdateListButton = uibutton(app.CalculateTab_PSD, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.ExportTab_2 = uitab(app.TabGroup3);
            app.ExportTab_2.Title = 'Export';
            app.PSDFunctionListBoxLabel = uilabel(app.ExportTab_2);
            app.PSDFunctionListBoxLabel.Text = 'PSD Function';
            app.PSDFunctionListBox = uilistbox(app.ExportTab_2);
            app.PSDFunctionListBox.Items = {'Displacement PSD', 'Stress PSD'};
            app.PSDFunctionListBox.Value = 'Displacement PSD';
            app.SaveButton_2 = uibutton(app.ExportTab_2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.DirlikFatigueButton = uibutton(app.ExportTab_2, 'push');
            app.DirlikFatigueButton.ButtonPushedFcn = createCallbackFcn(app, @DirlikFatigueButtonPushed, true);
            app.DirlikFatigueButton.Text = 'Dirlik Fatigue';
            app.OutputPSDArrayNameEditFieldLabel = uilabel(app.ExportTab_2);
            app.OutputPSDArrayNameEditFieldLabel.Text = 'Output PSD Array Name';
            app.OutputPSDArrayNameEditField = uieditfield(app.ExportTab_2, 'text');
            app.SaveModelButton = uibutton(app.RectangularPlateUniformPressureUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.RectangularPlateUniformPressureUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            % Show the figure after all components are created
            app.RectangularPlateUniformPressureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rectangular_plate_uniform_pressure_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RectangularPlateUniformPressureUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RectangularPlateUniformPressureUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RectangularPlateUniformPressureUIFigure)
        end
    end
end