classdef response_PSD_relative_damage_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        responsePSDdamageUIFigure       matlab.ui.Figure
        UnitEditField                   matlab.ui.control.EditField
        UnitEditFieldLabel              matlab.ui.control.Label
        RelativeDamageEditField         matlab.ui.control.EditField
        RelativeDamageEditFieldLabel    matlab.ui.control.Label
        InterpolationdeltafHzEditField  matlab.ui.control.EditField
        InterpolationdeltafHzEditFieldLabel  matlab.ui.control.Label
        FatigueExponentbEditField       matlab.ui.control.EditField
        FatigueExponentbEditFieldLabel  matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsfreqHzamplitudeunit2HzLabel  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        respone_PSD_relative_damage_aver15TomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        method % Description
        THM    %
    end
    

    methods (Access = private)

       function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
       end

        
       function update_psd(app)

            CloseFigures(app);
    
            app.RelativeDamageEditField.Visible='off';
            app.RelativeDamageEditFieldLabel.Visible='off';

            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density_alt();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_psd(app);
            app.RelativeDamageEditField.Visible='off';
            app.RelativeDamageEditFieldLabel.Visible='off';
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end 
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            CloseFigures(app);

            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 


            %%%%%%%%%%%%%%%%
            
            bex=str2num(app.FatigueExponentbEditField.Value);
            
            dur=str2num(app.DurationsecEditField.Value);
            
            
            f=app.THM(:,1);
            a=app.THM(:,2);
            
            df=str2num(app.InterpolationdeltafHzEditField.Value);
            
            [s,rms]=calculate_PSD_slopes_no(f,a);
            
            %
            [fi,ai]=interpolate_PSD(f,a,s,df);
            
            apsd=[fi ai];
            
            [damage]=Dirlik_fds(apsd,bex,dur,df);
            
            ss1=sprintf('%8.4g',damage);

            u=app.UnitEditField.Value;
                  
            ss2=sprintf('Relative Damage (%s^%g)',u,bex);

            app.RelativeDamageEditFieldLabel.Text=ss2;
 
            app.RelativeDamageEditField.Value=ss1;
            app.RelativeDamageEditField.Visible='on';
            app.RelativeDamageEditFieldLabel.Visible='on';

            fmin=f(1);
            fmax=f(end);
            fig_num=1;
            xlab='Frequency (Hz)';
            

            if(u=='G')
                t_string=sprintf('Power Spectral Density %6.3g %sRMS Overall ',rms,u);
                ylab=sprintf('Accel (%s^2/Hz)',u);
            else
                t_string=sprintf('Power Spectral Density %6.3g %s RMS Overall ',rms,u);
                ylab=sprintf('PSD (%s^2/Hz)',u);
            end


            plot_PSD_function(fig_num,xlab,ylab,t_string,app.THM,fmin,fmax);

        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            app.RelativeDamageEditField.Visible='off';
            app.RelativeDamageEditFieldLabel.Visible='off';
        end

        % Value changing function: FatigueExponentbEditField
        function FatigueExponentbEditFieldValueChanging(app, event)
            app.RelativeDamageEditField.Visible='off';
            app.RelativeDamageEditFieldLabel.Visible='off';
        end

        % Value changing function: InterpolationdeltafHzEditField
        function InterpolationdeltafHzEditFieldValueChanging(app, event)
            app.RelativeDamageEditField.Visible='off';
            app.RelativeDamageEditFieldLabel.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.responsePSDdamageUIFigure = uifigure('Visible', 'off');
            app.responsePSDdamageUIFigure.Name = 'response PSD damage';
            app.respone_PSD_relative_damage_aver15TomIrvineLabel = uilabel(app.responsePSDdamageUIFigure);
            app.respone_PSD_relative_damage_aver15TomIrvineLabel.Text = 'response_PSD_relative_damage_a   ver 1.5   Tom Irvine          The calculation is via the Dirlik method.';
            app.listbox_method_psd = uilistbox(app.responsePSDdamageUIFigure);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.responsePSDdamageUIFigure);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.TheinputarraymusthavetwocolumnsfreqHzamplitudeunit2HzLabel = uilabel(app.responsePSDdamageUIFigure);
            app.TheinputarraymusthavetwocolumnsfreqHzamplitudeunit2HzLabel.Text = {'The input array must have two columns:  freq (Hz) & amplitude (unit^2/Hz)'; ''};
            app.UpdateListButton_psd = uibutton(app.responsePSDdamageUIFigure, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.responsePSDdamageUIFigure);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.responsePSDdamageUIFigure);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.CalculateButton = uibutton(app.responsePSDdamageUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReturnButton = uibutton(app.responsePSDdamageUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.DurationsecEditFieldLabel = uilabel(app.responsePSDdamageUIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.responsePSDdamageUIFigure, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.FatigueExponentbEditFieldLabel = uilabel(app.responsePSDdamageUIFigure);
            app.FatigueExponentbEditFieldLabel.Text = 'Fatigue Exponent b';
            app.FatigueExponentbEditField = uieditfield(app.responsePSDdamageUIFigure, 'text');
            app.FatigueExponentbEditField.ValueChangingFcn = createCallbackFcn(app, @FatigueExponentbEditFieldValueChanging, true);
            app.InterpolationdeltafHzEditFieldLabel = uilabel(app.responsePSDdamageUIFigure);
            app.InterpolationdeltafHzEditFieldLabel.Text = 'Interpolation delta f (Hz)';
            app.InterpolationdeltafHzEditField = uieditfield(app.responsePSDdamageUIFigure, 'text');
            app.InterpolationdeltafHzEditField.ValueChangingFcn = createCallbackFcn(app, @InterpolationdeltafHzEditFieldValueChanging, true);
            app.InterpolationdeltafHzEditField.Value = '0.5';
            app.RelativeDamageEditFieldLabel = uilabel(app.responsePSDdamageUIFigure);
            app.RelativeDamageEditFieldLabel.Text = 'Relative Damage';
            app.RelativeDamageEditField = uieditfield(app.responsePSDdamageUIFigure, 'text');
            app.UnitEditFieldLabel = uilabel(app.responsePSDdamageUIFigure);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.UnitEditField = uieditfield(app.responsePSDdamageUIFigure, 'text');
            app.UnitEditField.Value = 'G';
            % Show the figure after all components are created
            app.responsePSDdamageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = response_PSD_relative_damage_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.responsePSDdamageUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.responsePSDdamageUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.responsePSDdamageUIFigure)
        end
    end
end