classdef response_velocity_one_system_bands_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIResponseVelocityOneSystemBandsUIFigure  matlab.ui.Figure
        response_velocity_one_system_bands_aver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        ViewEquationButton            matlab.ui.control.Button
        InputArrayNameEditFieldLabel  matlab.ui.control.Label
        InputArrayNameEditField       matlab.ui.control.EditField
        MassUnitListBoxLabel          matlab.ui.control.Label
        MassUnitListBox               matlab.ui.control.ListBox
        PowerUnitListBoxLabel         matlab.ui.control.Label
        PowerUnitListBox              matlab.ui.control.ListBox
        InputFormatListBoxLabel       matlab.ui.control.Label
        InputFormatListBox            matlab.ui.control.ListBox
        TheinputarraymustbeaonethirdoctavepowerspectrumLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        UniformLossFactorEditFieldLabel  matlab.ui.control.Label
        UniformLossFactorEditField    matlab.ui.control.NumericEditField
        MassEditFieldLabel            matlab.ui.control.Label
        MassEditField                 matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
        
        function FormatChange(app)
            a = app.InputFormatListBox.Value;
            if(contains(a,'Loss'))
                app.UniformLossFactorEditField.Visible='off';
                app.UniformLossFactorEditFieldLabel.Visible='off';
            else
                app.UniformLossFactorEditField.Visible='on';
                app.UniformLossFactorEditFieldLabel.Visible='on';                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            FormatChange(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            A = imread('power_velox_energy.jpg');
            figure(999);
            imshow(A,'border','tight','InitialMagnification',100) 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            FS = app.InputArrayNameEditField.Value;
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end                
            
            sz=size(THM);
            num=sz(1);
            
            fc=THM(:,1);
            power_1=THM(:,2);
            
            a = app.InputFormatListBox.Value;
            if(contains(a,'Loss'))
                lf1=THM(:,3);
            else    
                LF=app.UniformLossFactorEditField.Value;
                lf1=LF*ones(num,1);
            end
        
            m1=app.MassEditField.Value;
            
            mu=app.MassUnitListBox.Value;
            
            if(contains(mu,'lbm'))
                iu=1;
                m1=m1/2.2046;   % convert to kg
            else
                iu=2;
            end
            
            pp=strtrim(app.PowerUnitListBox.Value);
            
            if(contains(pp,'dB'))
                Lwb=THM;
                pu=1;
                for i=1:num
                    power_1(i)=(1e-12)*10^(power_1(i)/10);
                end
            end    
            if(strcmp(pp,'W'))
                pu=2;
            end   
            if(strcmp(pp,'in-lbf/sec'))            
                pu=3;
                power_1=power_1/8.8504; % convert in-lbf/sec to W
            end

            if(pu>=2)
                
                Lwb=zeros(num,2);
                
                for i=1:num
                    PP=10*log10(power_1(i)/(1e-12));                    
                    Lwb(i,:)=[fc(i) PP];
                end
            end
            
            tpi=2*pi;
            
            omega=tpi*fc;
            
            NL=length(fc);
            
            v1=zeros(NL,1);
            a1=zeros(NL,1);
            
            for i=1:NL
            
                A=lf1(i);
                B=power_1(i)/omega(i);
            
                E=B/A;
                
                [v1(i),a1(i)]=energy_to_velox_accel(E,m1,omega(i));
                
            end
            
            if(iu==1)
               a1=a1/386;
            else
               a1=a1/9.81;
               v1=v1*100;
            end    
            
            disp(' ');
            disp(' * * * * ');
            disp(' ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            
            fmin=fc(1);
            fmax=fc(end);
            
            md=4;
            ppp1=[fc v1];
            
            % t_string='Velocity Spectrum';
            
            % [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
            %               y_label,t_string,ppp1,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            aaa1=[fc a1];
            
            % y_label='Accel (GRMS)';
            % t_string='Acceleration Spectrum';
            
            % [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
            %                         y_label,t_string,aaa1,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
            [fig_num]=power_bar_alt(fig_num,fc,Lwb(:,2));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       
            [fig_num]=ILF_bar_alt(fig_num,fc,lf1);            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp('    Responses RMS ');
            disp(' ')
            
            
            disp(' Freq     vel       accel');
               
            if(iu==1)
                disp(' (Hz)   (in/sec)     (G)');    
            else
                disp(' (Hz)   (m/sec)     (G)');  
            end
                
            for i=1:NL
                fprintf(' %g   %8.4g   %8.4g   %8.4g \n',fc(i),v1(i),a1(i));    
            end
            
            %%%%%%%%%%%%%%%%%
            
                nb=1;   % for 1/3 octave band
                                    
                [vpsd,vrms]=psd_from_spectrum(nb,fc,v1);   

                vpsd=[fc vpsd];
                
                rrr1=vpsd;
                
                if(iu==1)
                    fprintf('\n Overall Level = %8.4g in/sec RMS \n',vrms);
                    y_label='Vel ((in/sec)^2/Hz)';
                    t_string=sprintf('Velocity Power Spectral Density  %7.3g in/sec RMS Overall',vrms);  
                else
                    fprintf('\n Overall Level = %8.4g m/sec RMS \n',vrms);
                    y_label='Vel ((m/sec)^2/Hz)';
                    t_string=sprintf('Velocity Power Spectral Density  %7.3g m/sec RMS Overall',vrms);                      
                end
                
                x_label='Center Frequency (Hz)';
                
                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);                  
            
            %%%%%%%%%%%%%%%%%
                
            %%%%%%%%%%%%%%%%%    
                
                [psd,grms]=psd_from_spectrum(nb,fc,a1);   
                
                disp(' ');
                disp(' Power Spectral Density ');
                disp(' ');
                disp('   Freq    Accel');
                disp('   (Hz)   (G^2/Hz)');
                
                for i=1:NL
                    fprintf('  %g    %8.4g \n',fc(i),psd(i));
                end
                
                fprintf('\n Overall Level = %8.4g GRMS \n',grms);
                
                psd=[fc psd];
                
                rrr1=psd;
                
                y_label='Accel (G^2/Hz)';
                
                t_string=sprintf('Acceleration Power Spectral Density  %7.3g GRMS Overall',grms);
            
                [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,...
                           y_label,t_string,rrr1,fmin,fmax,md);    
                
            
            %%%%%%%%%%%%%%%%%
                
            assignin('base', 'vel_ps',ppp1);
            assignin('base', 'accel_ps',aaa1);
            assignin('base', 'vel_psd',vpsd) 
            assignin('base', 'accel_psd',psd) 
            
            disp(' ');
            disp(' Output Arrays ');
            disp(' ');
            disp(' Power Spectra: vel_ps & accel_ps ');
            disp(' Power Spectral Densities: vel_psd & accel_psd ');            
            disp(' ');
            
        end

        % Value changed function: InputFormatListBox
        function InputFormatListBoxValueChanged(app, event)
            FormatChange(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIResponseVelocityOneSystemBandsUIFigure = uifigure('Visible', 'off');
            app.UIResponseVelocityOneSystemBandsUIFigure.Name = 'UI Response Velocity One System Bands';
            app.response_velocity_one_system_bands_aver10byTomIrvineLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.response_velocity_one_system_bands_aver10byTomIrvineLabel.Text = 'response_velocity_one_system_bands_a  ver 1.0  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIResponseVelocityOneSystemBandsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ViewEquationButton = uibutton(app.UIResponseVelocityOneSystemBandsUIFigure, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.InputArrayNameEditFieldLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIResponseVelocityOneSystemBandsUIFigure, 'text');
            app.MassUnitListBoxLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.MassUnitListBoxLabel.Text = 'Mass Unit';
            app.MassUnitListBox = uilistbox(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.MassUnitListBox.Items = {'lbm', 'kg'};
            app.MassUnitListBox.Value = 'lbm';
            app.PowerUnitListBoxLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.PowerUnitListBoxLabel.Text = 'Power Unit';
            app.PowerUnitListBox = uilistbox(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.PowerUnitListBox.Items = {'dB (ref 1 pW)', 'W', 'in-lbf/sec'};
            app.PowerUnitListBox.Value = 'dB (ref 1 pW)';
            app.InputFormatListBoxLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.InputFormatListBoxLabel.Text = 'Input Format';
            app.InputFormatListBox = uilistbox(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.InputFormatListBox.Items = {'Freq, Power', 'Freq, Power, Loss Factor'};
            app.InputFormatListBox.ValueChangedFcn = createCallbackFcn(app, @InputFormatListBoxValueChanged, true);
            app.InputFormatListBox.Value = 'Freq, Power';
            app.TheinputarraymustbeaonethirdoctavepowerspectrumLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.TheinputarraymustbeaonethirdoctavepowerspectrumLabel.Text = 'The input array must be a one-third octave power spectrum.';
            app.CalculateButton = uibutton(app.UIResponseVelocityOneSystemBandsUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UniformLossFactorEditFieldLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.UniformLossFactorEditFieldLabel.Text = 'Uniform Loss Factor';
            app.UniformLossFactorEditField = uieditfield(app.UIResponseVelocityOneSystemBandsUIFigure, 'numeric');
            app.UniformLossFactorEditField.Value = 0.1;
            app.MassEditFieldLabel = uilabel(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.UIResponseVelocityOneSystemBandsUIFigure, 'numeric');
            app.Image = uiimage(app.UIResponseVelocityOneSystemBandsUIFigure);
            app.Image.ImageSource = 'SEA_model_1.jpg';
            % Show the figure after all components are created
            app.UIResponseVelocityOneSystemBandsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = response_velocity_one_system_bands_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIResponseVelocityOneSystemBandsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIResponseVelocityOneSystemBandsUIFigure)
        end
    end
end