classdef sdof_fn_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        Reset_2       matlab.ui.control.Button
        uipanel2      matlab.ui.container.Panel
        leftlistbox   matlab.ui.control.ListBox
        rightlistbox  matlab.ui.control.ListBox
        right_input   matlab.ui.control.EditField
        left_input    matlab.ui.control.EditField
        right_text    matlab.ui.control.Label
        left_text     matlab.ui.control.Label
        text6         matlab.ui.control.Label
        text5         matlab.ui.control.Label
        Reset         matlab.ui.control.Button
        Calculate     matlab.ui.control.Button
        uipanel3      matlab.ui.container.Panel
        Answer        matlab.ui.control.TextArea
        uipanel1      matlab.ui.container.Panel
        toplistbox    matlab.ui.control.ListBox
        axes1         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function clear_Answer(app, hObject, eventdata, handles)
            %
            set(handles.Answer,'String',' ');
            set(handles.Answer,'Enable','off');
            %
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sdof_fn_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to sdof_fn (see VARARGIN)
            
            % Choose default command line output for sdof_fn
            handles.output = hObject;
            
            % add some additional data as a new field called numberOfErrors
            
            set(handles.Answer,'Enable','off');
            
            %%%%


            cmap(1,:)=[0 0.1 0.4];        % dark blue
            cmap(2,:)=[0.8 0 0];        % red
            cmap(3,:)=[0 0 0];          % black
            cmap(4,:)=[0.6 0.3 0];      % brown
            cmap(5,:)=[0 0.5 0.5];      % teal
            cmap(6,:)=[1 0.5 0];        % orange
            cmap(7,:)=[0.5 0.5 0];      % olive
            cmap(8,:)=[0.13 0.55 0.13]; % forest green
            cmap(9,:)=[0.5 0 0];        % maroon
            cmap(10,:)=[0.5 0.5 0.5 ];  % grey
            cmap(11,:)=[1. 0.4 0.4];    % pink-orange
            cmap(12,:)=[0.5 0.5 1];     % lavender
            cmap(13,:)=[0.05 0.7 1.];   % light blue
            cmap(14,:)=[0  0.8 0.4 ];   % green
            cmap(15,:)=[1 0.84 0];      % gold
            cmap(16,:)=[0 0.8 0.8];     % turquoise
            
            clc;
            axes(handles.axes1);
            x=[-4 -4 4 4 -4];
            y=[4 7 7 4 4]+1;
            
            plot(x,y,'Color',cmap(1,:),'linewidth',0.75);
            hold on;
            x=[-5 5];
            y=[ 1 1];
            plot(x,y,'Color','k','linewidth',0.75);
            y=[0.5 1];
            x=[-0.7 0];
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-1.4;
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-2.8;
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-4.2;
            plot(x,y,'Color','k','linewidth',0.75);
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+1.4;
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+2.8;
            plot(x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+4.2;
            plot(x,y,'Color','k','linewidth',0.75);
            
            text(-0.9,6.5,'m','fontsize',13);
            text(-3.5,3,'k','fontsize',13);
            
            nn=2000;
            
            dt=4/(nn-1);
            
            t=zeros(nn,1);
            for i=1:nn
                t(i)=(i-1)*dt;
            end
            t=t-0.75;
            y=sawtooth(2*pi*t,0.5);
            y1=y;
            
            t=3*t/(max(t)-min(t));
            
            plot(y,t+2,'Color',cmap(5,:),'linewidth',0.75);
            x=[0 0];
            y=[1 1.5];
            plot(x,y,'Color',cmap(5,:),'linewidth',0.75);
            
            
            x=[0 0];
            y=[max(t)+2 5];
            plot(x,y,'Color',cmap(5,:),'linewidth',0.75);
            
            hold off;
            grid on;
            set(gca,'XTick',[], 'YTick', [])
            xlim([-10 10]);
            ylim([0 10]);
            
            
            %%%%


            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Calculate
        function Calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.Answer,'Enable','on');
            
            tpi=2*pi;
            
            Nm_per_lbfin=175.13;
            
            kg_per_lbm=0.45351;
            kg_per_lbs2pin=175.07;
            
            in_per_meter=39.3701;
            
            mass=0;
            stiff=0;
            om=0;
            
            
            na=handles.left_value;
            nb=handles.right_value;
            
            
            AnalysisSelection=get(handles.toplistbox,'value');
            LeftSelection=get(handles.leftlistbox,'Value');
            RightSelection=get(handles.rightlistbox,'Value');


            if(AnalysisSelection==1)  % calculate natural frequency
            
                if(LeftSelection==1)  % convert mass to kg
                    na=na*kg_per_lbm;
                end
                if(LeftSelection==2)
                    na=na*kg_per_lbs2pin;
                end
            
                if(RightSelection==1)  % convert stiffness to N/m
                    nb=nb*Nm_per_lbfin;
                end
                if(RightSelection==2)
                    nb=nb*Nm_per_lbfin/12;
                end
                if(RightSelection==4)
                    nb=nb*1000;
                end
            
                mass=na;
                stiff=nb;
            
                om=sqrt(stiff/mass);
                fn=om/tpi;
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                fn_s=sprintf('natural frequency=\n%8.4g Hz \n%8.4g rad/sec',fn,om);
            
                fn_s=strcat(fn_s,sd_s);
            
                set(handles.Answer,'String',fn_s);
            end
            if(AnalysisSelection==2)  % calculate mass
            
                if(LeftSelection==1)  % convert frequency to rad/sec
                    na=na*tpi;
                end
            
                if(RightSelection==1)  % convert stiffness to N/m
                    nb=nb*Nm_per_lbfin;
                end
                if(RightSelection==2)
                    nb=nb*Nm_per_lbfin/12;
                end
                if(RightSelection==4)
                    nb=nb*1000;
                end
            
                om=na;
                stiff=nb;
            
                mass=stiff/om^2;
            
                mass_s=sprintf('mass=\n%8.4g lbm \n%8.4g lbf sec^2/in \n%8.4g kg',...
                                           mass/kg_per_lbm,(mass/kg_per_lbm)/386,mass);
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                mass_s=strcat(mass_s,sd_s);
            
                set(handles.Answer,'String',mass_s);
            end
            if(AnalysisSelection==3)  % calculate stiffness
            
            
                if(LeftSelection==1)  % convert frequency to rad/sec
                    na=na*tpi;
                end
            
                if(RightSelection==1)  % convert mass to kg
                    nb=nb*kg_per_lbm;
                end
                if(RightSelection==2)
                    nb=nb*kg_per_lbs2pin;
                end
            
            
                om=na;
                mass=nb;
            
                stiff=mass*om^2;


                stiff_s=sprintf('stiffness= \n%8.5g lbf/in \n%8.5g lbf/ft \n%8.5g N/m\n%8.5g N/mm',...
                            stiff/Nm_per_lbfin,12*stiff/Nm_per_lbfin,stiff,stiff/1000);
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                stiff_s=strcat(stiff_s,sd_s);
            
            
                set(handles.Answer,'String',stiff_s);
            end
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            allhandles=findobj(gcf,'style','edit');
            for i=1:length(allhandles)
               set(allhandles(i),'string','');
            end
            handles.left_value=0;
            handles.right_value=0;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: left_input
        function left_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to left_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of left_input as text
            %        str2double(get(hObject,'String')) returns contents of left_input as a double
            
            clear_Answer(app, hObject, eventdata, handles);
            
            string=get(hObject,'String');
            handles.left_value=str2num(string);
            
            guidata(hObject, handles);
        end

        % Value changed function: leftlistbox
        function leftlistbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to leftlistbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns leftlistbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from leftlistbox
            
            clear_Answer(app, hObject, eventdata, handles);
            
            handles.left_index= get(hObject,'value');
            
            
            guidata(hObject, handles);
        end

        % Value changed function: right_input
        function right_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to right_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of right_input as text
            %        str2double(get(hObject,'String')) returns contents of right_input as a double
            clear_Answer(app, hObject, eventdata, handles);
            
            string=get(hObject,'String');
            handles.right_value=str2num(string);
            
            guidata(hObject, handles);
        end

        % Value changed function: rightlistbox
        function rightlistbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rightlistbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns rightlistbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from rightlistbox
            clear_Answer(app, hObject, eventdata, handles);
            
            handles.right_index=get(hObject,'value');
            
            guidata(hObject, handles);
        end

        % Value changed function: toplistbox
        function toplistbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toplistbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns toplistbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from toplistbox
            
            calculation=get(handles.toplistbox,'value');
            
            clear_Answer(app, hObject, eventdata, handles);
            
            set(handles.rightlistbox, 'String', '');
            set(handles.leftlistbox, 'String', '');
            
            set(handles.rightlistbox,'Visible','on');
            set(handles.leftlistbox,'Visible','on');
            
            if(calculation==1)  % natural frequency
                clear string;
                set(handles.left_text,'String','Mass');
                string{1}='lbm';
                string{2}='lbf sec^2/in';
                string{3}='kg';
                set(handles.leftlistbox,'String',string)
            
                set(handles.right_text,'String','Stiffness');
                clear string;
                string{1}='lbf/in';
                string{2}='lbf/ft';
                string{3}='N/m';
                string{4}='N/mm';
                set(handles.rightlistbox,'String',string)
            
            end
            if(calculation==2)  % mass
                set(handles.left_text,'String','Natural Frequency');
                clear string;
                string{1}='Hz';
                string{2}='rad/sec';
                set(handles.leftlistbox,'String',string)
            
                set(handles.right_text,'String','Stiffness');
                clear string;
                string{1}='lbf/in';
                string{2}='lbf/ft';
                string{3}='N/m';
                string{4}='N/mm';
                set(handles.rightlistbox,'String',string)
            end
            if(calculation==3)  % stiffness
                set(handles.left_text,'String','Natural Frequency');
                clear string;
                string{1}='Hz';
                string{2}='rad/sec';
                set(handles.leftlistbox,'String',string)
            
                set(handles.right_text,'String','Mass');
                clear string;
                string{1}='lbm';
                string{2}='lbf sec^2/in';
                string{3}='kg';
                set(handles.rightlistbox,'String',string)
            end
        end

        % Button pushed function: Reset_2
        function Reset_2ButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sdof_fn';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Select Output Parameter';
            app.uipanel1.Tag = 'uipanel1';
            app.toplistbox = uilistbox(app.uipanel1);
            app.toplistbox.Items = {'natural frequency', 'mass', 'stiffness'};
            app.toplistbox.ValueChangedFcn = createCallbackFcn(app, @toplistbox_Callback, true);
            app.toplistbox.Tag = 'toplistbox';
            app.toplistbox.Value = 'natural frequency';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Results';
            app.uipanel3.Tag = 'uipanel3';
            app.Answer = uitextarea(app.uipanel3);
            app.Answer.Tag = 'Answer';
            app.Calculate = uibutton(app.figure1, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @Calculate_Callback, true);
            app.Calculate.Tag = 'Calculate';
            app.Calculate.Text = 'Calculate';
            app.Reset = uibutton(app.figure1, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Tag = 'Reset';
            app.Reset.Text = 'Reset';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'sdof_fn  ver 1.6     by Tom Irvine';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'This script calculates the natural frequency of a single-degree-of-freedom system.';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Enter Data';
            app.uipanel2.Tag = 'uipanel2';
            app.left_text = uilabel(app.uipanel2);
            app.left_text.Tag = 'left_text';
            app.left_text.Text = 'Mass';
            app.right_text = uilabel(app.uipanel2);
            app.right_text.Tag = 'right_text';
            app.right_text.Text = 'Stiffness';
            app.left_input = uieditfield(app.uipanel2, 'text');
            app.left_input.ValueChangedFcn = createCallbackFcn(app, @left_input_Callback, true);
            app.left_input.Tag = 'left_input';
            app.right_input = uieditfield(app.uipanel2, 'text');
            app.right_input.ValueChangedFcn = createCallbackFcn(app, @right_input_Callback, true);
            app.right_input.Tag = 'right_input';
            app.rightlistbox = uilistbox(app.uipanel2);
            app.rightlistbox.Items = {'lbf/in', 'lbf/ft', 'N/m', 'N/mm'};
            app.rightlistbox.ValueChangedFcn = createCallbackFcn(app, @rightlistbox_Callback, true);
            app.rightlistbox.Tag = 'rightlistbox';
            app.rightlistbox.Value = 'lbf/in';
            app.leftlistbox = uilistbox(app.uipanel2);
            app.leftlistbox.Items = {'lbm', 'lbf sec^2/in', 'kg'};
            app.leftlistbox.ValueChangedFcn = createCallbackFcn(app, @leftlistbox_Callback, true);
            app.leftlistbox.Tag = 'leftlistbox';
            app.leftlistbox.Value = 'lbm';
            app.Reset_2 = uibutton(app.figure1, 'push');
            app.Reset_2.ButtonPushedFcn = createCallbackFcn(app, @Reset_2ButtonPushed, true);
            app.Reset_2.Tag = 'Reset';
            app.Reset_2.Text = 'Return';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sdof_fn_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sdof_fn_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end