classdef sdof_fn_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sdoffnUIFigure                  matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        NaturalFrequencyTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MassStiffnessTab                matlab.ui.container.Tab
        OutputListBox                   matlab.ui.control.ListBox
        OutputListBoxLabel              matlab.ui.control.Label
        RightListBox                    matlab.ui.control.ListBox
        RightListBoxLabel               matlab.ui.control.Label
        LeftListBox                     matlab.ui.control.ListBox
        LeftListBoxLabel                matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        EditFieldRight                  matlab.ui.control.EditField
        EditFieldLeft                   matlab.ui.control.EditField
        StaticDeflectionTab             matlab.ui.container.Tab
        UnitListBox                     matlab.ui.control.ListBox
        StaticDeflectionEditField       matlab.ui.control.EditField
        StaticDeflectionEditFieldLabel  matlab.ui.control.Label
        CalculateButton_3               matlab.ui.control.Button
        FreeResponsetoInitialConditionsTab  matlab.ui.container.Tab
        StiffnesslbfinEditField         matlab.ui.control.EditField
        StiffnesslbfinEditFieldLabel    matlab.ui.control.Label
        MasslbmEditField                matlab.ui.control.EditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        MethodListBox                   matlab.ui.control.ListBox
        MethodListBoxLabel              matlab.ui.control.Label
        DampingPercentEditField         matlab.ui.control.EditField
        DampingPercentEditFieldLabel    matlab.ui.control.Label
        NumberofCyclesEditField         matlab.ui.control.EditField
        NumberofCyclesEditFieldLabel    matlab.ui.control.Label
        InitialVelocityinsecEditField   matlab.ui.control.EditField
        InitialVelocityinsecEditFieldLabel  matlab.ui.control.Label
        InitialDisplacementinEditField  matlab.ui.control.EditField
        InitialDisplacementinEditFieldLabel  matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        sdof_fn_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        nCalculation  % 
        nLeft         %
        nRight        %
        nfree_method  %
        nfree_unit    %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end

        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';          
        end
        function change_unit_method(app)
            clear(app);

            Items = char(app.MethodListBox.Items);
            im=app.MethodListBox.Value;
            [app.nfree_method]=find_index(Items,im);

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.nfree_unit]=find_index(Items,im);

            n=app.nfree_unit;
            m=app.nfree_method;

            if(n==1)
                app.InitialDisplacementinEditFieldLabel.Text='Initial Displacement (in)';    
                app.InitialVelocityinsecEditFieldLabel.Text='Initial Velocity (in/sec)';   
                app.MasslbmEditFieldLabel.Text='Mass (lbm)'; 
                app.StiffnesslbfinEditFieldLabel.Text='Stiffness (lbf/in)';    
            else
                app.InitialDisplacementinEditFieldLabel.Text='Initial Displacement (mm)';   
                app.InitialVelocityinsecEditFieldLabel.Text='Initial Velocity (cm/sec)';   
                app.MasslbmEditFieldLabel.Text='Mass (kg)';   
                app.StiffnesslbfinEditFieldLabel.Text='Stiffness (N/mm)';       
            end
 
            app.MasslbmEditField.Visible='off'; 
            app.StiffnesslbfinEditField.Visible='off';          
            app.MasslbmEditFieldLabel.Visible='off'; 
            app.StiffnesslbfinEditFieldLabel.Visible='off';  
            app.NaturalFrequencyHzEditField.Visible='off';
            app.NaturalFrequencyHzEditFieldLabel.Visible='off';   

            if(m==1)
                app.NaturalFrequencyHzEditField.Visible='on';
                app.NaturalFrequencyHzEditFieldLabel.Visible='on';       
            else
                app.MasslbmEditField.Visible='on'; 
                app.StiffnesslbfinEditField.Visible='on';          
                app.MasslbmEditFieldLabel.Visible='on'; 
                app.StiffnesslbfinEditFieldLabel.Visible='on';   
            end

        end    

        function Change(app)
            clear(app);         
            
            Items = char(app.OutputListBox.Items);
            im=app.OutputListBox.Value;
            [app.nCalculation]=find_index(Items,im);       
            
            Items = char(app.LeftListBox.Items);
            im=app.LeftListBox.Value;
            [app.nLeft]=find_index(Items,im);                 
            
            Items = char(app.RightListBox.Items);
            im=app.RightListBox.Value;
            [app.nRight]=find_index(Items,im);      

            app.LeftListBox.Visible='on';
            app.RightListBox.Visible='on';

            if(app.nCalculation==1)  % natural frequency
                clear string;
                app.LeftListBoxLabel.Text='Mass';
                string{1}='lbm';
                string{2}='lbf sec^2/in';
                string{3}='kg';
                app.LeftListBox.Items=string;
            
                app.RightListBoxLabel.Text='Stiffness';
                clear string;
                string{1}='lbf/in';
                string{2}='lbf/ft';
                string{3}='N/m';
                string{4}='N/mm';
                app.RightListBox.Items=string;
            
            end
            if(app.nCalculation==2)  % mass
                app.LeftListBoxLabel.Text='Natural Frequency';
                clear string;
                string{1}='Hz';
                app.LeftListBox.Items=string;
            
                app.RightListBoxLabel.Text='Stiffness';
                clear string;
                string{1}='lbf/in';
                string{2}='lbf/ft';
                string{3}='N/m';
                string{4}='N/mm';
                app.RightListBox.Items=string;
            end
            if(app.nCalculation==3)  % stiffness
                app.LeftListBoxLabel.Text='Natural Frequency';
                clear string;
                string{1}='Hz';
                string{2}='rad/sec';
                app.LeftListBox.Items=string;
            
                app.RightListBoxLabel.Text='Mass';
                clear string;
                string{1}='lbm';
                string{2}='lbf sec^2/in';
                string{3}='kg';
                app.RightListBox.Items=string;
            end

        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Change(app);
            change_unit_method(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear(app);

            tpi=2*pi;
            
            Nm_per_lbfin=175.13;
            kg_per_lbm=0.45351;
            kg_per_lbs2pin=175.07;
            in_per_meter=39.3701;
                        
            na=str2num(app.EditFieldLeft.Value);
            nb=str2num(app.EditFieldRight.Value);
            
            AnalysisSelection=app.nCalculation;
            LeftSelection=app.nLeft;
            RightSelection=app.nRight;
            
            if(AnalysisSelection==1)  % calculate natural frequency
            
                if(LeftSelection==1)  % convert mass to kg
                    na=na*kg_per_lbm;
                end
                if(LeftSelection==2)
                    na=na*kg_per_lbs2pin;
                end
            
                if(RightSelection==1)  % convert stiffness to N/m
                    nb=nb*Nm_per_lbfin;
                end
                if(RightSelection==2)
                    nb=nb*Nm_per_lbfin/12;
                end
                if(RightSelection==4)
                    nb=nb*1000;
                end
            
                mass=na;
                stiff=nb;
            
                om=sqrt(stiff/mass);
                fn=om/tpi;
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                fn_s=sprintf('natural frequency=\n%8.4g Hz \n%8.4g rad/sec',fn,om);
            
                fn_s=strcat(fn_s,sd_s);
                app.ResultsTextArea.Value=fn_s;
            end
            if(AnalysisSelection==2)  % calculate mass
            
                if(LeftSelection==1)  % convert frequency to rad/sec
                    na=na*tpi;
                end
            
                if(RightSelection==1)  % convert stiffness to N/m
                    nb=nb*Nm_per_lbfin;
                end
                if(RightSelection==2)
                    nb=nb*Nm_per_lbfin/12;
                end
                if(RightSelection==4)
                    nb=nb*1000;
                end
            
                om=na;
                stiff=nb;
            
                mass=stiff/om^2;
            
                mass_s=sprintf('mass=\n%8.4g lbm \n%8.4g lbf sec^2/in \n%8.4g kg',...
                                           mass/kg_per_lbm,(mass/kg_per_lbm)/386,mass);
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                mass_s=strcat(mass_s,sd_s);
                app.ResultsTextArea.Value=mass_s;
            end
            if(AnalysisSelection==3)  % calculate stiffness
            
                if(LeftSelection==1)  % convert frequency to rad/sec
                    na=na*tpi;
                end
            
                if(RightSelection==1)  % convert mass to kg
                    nb=nb*kg_per_lbm;
                end
                if(RightSelection==2)
                    nb=nb*kg_per_lbs2pin;
                end
            
                om=na;
                mass=nb;
            
                stiff=mass*om^2;
           
                stiff_s=sprintf('stiffness= \n%8.5g lbf/in \n%8.5g lbf/ft \n%8.5g N/m\n%8.5g N/mm',...
                            stiff/Nm_per_lbfin,12*stiff/Nm_per_lbfin,stiff,stiff/1000);
            
                sd=mass*9.81/stiff;
                sd_s=sprintf('\n\n static deflection at 1 G=\n%8.4g in\n%8.4g mm',...
                               sd*in_per_meter,sd*1000);
            
                stiff_s=strcat(stiff_s,sd_s);
                app.ResultsTextArea.Value=stiff_s;

            end

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';  
        end

        % Value changed function: OutputListBox
        function OutputListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: LeftListBox
        function LeftListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changed function: RightListBox
        function RightListBoxValueChanged(app, event)
            Change(app);
        end

        % Value changing function: EditFieldLeft
        function EditFieldLeftValueChanging(app, event)
            clear(app);
        end

        % Value changing function: EditFieldRight
        function EditFieldRightValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function ReturnButton_2Pushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            change_unit_method(app);
        end

        % Value changed function: MethodListBox
        function MethodListBoxValueChanged(app, event)
            change_unit_method(app);
        end

        % Value changing function: InitialDisplacementinEditField
        function InitialDisplacementinEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: InitialVelocityinsecEditField
        function InitialVelocityinsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DampingPercentEditField
        function DampingPercentEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MasslbmEditField
        function MasslbmEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StiffnesslbfinEditField
        function StiffnesslbfinEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            clear(app);

            fig_num=1;
            
            iunit=app.nfree_unit;
            m=app.nfree_method;    
            
            d0=str2num(app.InitialDisplacementinEditField.Value);
            v0=str2num(app.InitialVelocityinsecEditField.Value);
            
            if(iunit>=2)
                d0=d0/1000;
                v0=v0/100;
            end
            
            damp=(1/100)*str2num(app.DampingPercentEditField.Value);
            
            if(damp>1)
                warndlg('Damping must be <= 100%% ');
                return;
            end
            
            nc=str2num(app.NumberofCyclesEditField.Value);
            
            if(m==1)
                fn=str2num(app.NaturalFrequencyHzEditField.Value);
                out1=sprintf(' fn=%g Hz',fn);
            else
                
               mass=str2num(app.MasslbmEditField.Value);    
               stiffness=str2num(app.StiffnesslbfinEditField.Value); 
               
               if(iunit==1)
                    mass=mass/386;
               else
                    stiffness=stiffness*1000;
               end    
               
               fn=sqrt(stiffness/mass)/(2*pi);
               out1=sprintf(' fn=%8.4g Hz',fn);
            end
            disp(out1);
            
            T=1/fn;
            
            sr=64*fn;
            dt=1/sr;
            
            nt=ceil(nc*T/dt);
            
            omegan=2*pi*fn;
            omegan2=omegan^2;
            omegad=sqrt(1-damp^2)*omegan;
            domegan=damp*omegan;
            
            t=zeros(nt,1);
            a=zeros(nt,1);
            v=zeros(nt,1);
            d=zeros(nt,1);
            
            R=( v0 + (domegan*d0))/omegad;
            
            for i=1:nt
               t(i)=(i-1)*dt;
               
                eee=exp(-domegan*t(i));
               
               cwdt=cos(omegad*t(i));
               swdt=sin(omegad*t(i));
               
               if(damp<1)
               
                    d(i)= eee*(d0*cwdt+R*swdt);
                    v(i)= -domegan*d(i)+omegad*eee*(-d0*swdt+R*cwdt);
                    a(i)= -omegan2*d(i)-2*domegan*v(i);
               
               else
                    d(i)= eee*(d0+(v0+omegan*d0)*t(i));
                    v(i)= -domegan*d(i)+(v0+omegan*d0);
                    a(i)= -omegan2*d(i);
               end
            end
            
            
            if(iunit==1)
                a=a/386;
                ay='Accel (G)';
                vy='Vel (in/sec)';
                dy='Disp (in)';
            end
            if(iunit==2)
                a=a/9.81;
                ay='Accel (G)';    
            end
            if(iunit==3)
                ay='Accel (m/sec^2)';     
            end
            if(iunit==2 || iunit==3)
                v=v*100;
                d=d*1000;   
                vy='Vel (cm/sec)';
                dy='Disp (mm)';    
            end
            
            [fig_num,hp]=plot_avd_time_histories_subplots_altp(fig_num,t,a,v,d,ay,vy,dy);
            
              
            %%%%%%%%%%%%%%%%%%%%%%
            
            
            s1=sprintf(' Maximum Absolute Values ');
            
            
            out1=sprintf(' %s  %8.4g  ',ay,max(abs(a)));
            out2=sprintf(' %s  %8.4g  ',vy,max(abs(v)));
            out3=sprintf(' %s  %8.4g  ',dy,max(abs(d)));
            
            ss=sprintf(' %s \n\n %s \n %s \n %s ',s1,out1,out2,out3);
            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';  

            %%%%%%%%%%%%%%%%%%%%%%
            
            aname='free_acceleration';
            vname='free_velocity';
            dname='free_displacement';
            
            assignin('base', aname, [t a]);
            assignin('base', vname, [t v]);
            assignin('base', dname, [t d]);
            
            message = sprintf('\nOutput Time History Arrays:\n\n  %s\n  %s\n  %s',aname,vname,dname);
            msgbox(message);
            

        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            Items = strtrim(app.UnitListBox.Items);
            im = strtrim(app.UnitListBox.Value);
            n = find(strcmp(Items, im));

            d=str2num(app.StaticDeflectionEditField.Value);
            
            if(n==1)
                G=386;
            else
                G=9.81;
                d=d/1000;
            end

            omega=sqrt(G/d);

            fn=omega/(2*pi);

            fn_s=sprintf('natural frequency=\n\n%8.4g Hz \n%8.4g rad/sec',fn,omega);
            
            app.ResultsTextArea.Value=fn_s;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button down function: StaticDeflectionTab
        function StaticDeflectionTabButtonDown(app, event)
            app.ResultsTextArea.Value='';
        end

        % Size changed function: MassStiffnessTab
        function MassStiffnessTabSizeChanged(app, event)

        end

        % Size changed function: FreeResponsetoInitialConditionsTab
        function FreeResponsetoInitialConditionsTabSizeChanged(app, event)

        end

        % Button down function: FreeResponsetoInitialConditionsTab
        function FreeResponsetoInitialConditionsTabButtonDown(app, event)
            app.ResultsTextArea.Value='';
        end

        % Button down function: NaturalFrequencyTab
        function NaturalFrequencyTabButtonDown(app, event)
            app.ResultsTextArea.Value='';
        end

        % Button down function: MassStiffnessTab
        function MassStiffnessTabButtonDown(app, event)
            app.ResultsTextArea.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.sdoffnUIFigure = uifigure('Visible', 'off');
            app.sdoffnUIFigure.Name = 'sdof fn';
            app.sdof_fn_aver10byTomIrvineLabel = uilabel(app.sdoffnUIFigure);
            app.sdof_fn_aver10byTomIrvineLabel.Text = 'sdof_fn_a    ver 1.0  by Tom Irvine';
            app.TabGroup = uitabgroup(app.sdoffnUIFigure);
            app.NaturalFrequencyTab = uitab(app.TabGroup);
            app.NaturalFrequencyTab.Title = 'Natural Frequency';
            app.NaturalFrequencyTab.ButtonDownFcn = createCallbackFcn(app, @NaturalFrequencyTabButtonDown, true);
            app.TabGroup2 = uitabgroup(app.NaturalFrequencyTab);
            app.MassStiffnessTab = uitab(app.TabGroup2);
            app.MassStiffnessTab.SizeChangedFcn = createCallbackFcn(app, @MassStiffnessTabSizeChanged, true);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.MassStiffnessTab.ButtonDownFcn = createCallbackFcn(app, @MassStiffnessTabButtonDown, true);
            app.EditFieldLeft = uieditfield(app.MassStiffnessTab, 'text');
            app.EditFieldLeft.ValueChangingFcn = createCallbackFcn(app, @EditFieldLeftValueChanging, true);
            app.EditFieldRight = uieditfield(app.MassStiffnessTab, 'text');
            app.EditFieldRight.ValueChangingFcn = createCallbackFcn(app, @EditFieldRightValueChanging, true);
            app.CalculateButton = uibutton(app.MassStiffnessTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LeftListBoxLabel = uilabel(app.MassStiffnessTab);
            app.LeftListBoxLabel.Text = 'Left';
            app.LeftListBox = uilistbox(app.MassStiffnessTab);
            app.LeftListBox.Items = {'lbm', 'lbf sec^2/in', 'kg'};
            app.LeftListBox.ValueChangedFcn = createCallbackFcn(app, @LeftListBoxValueChanged, true);
            app.LeftListBox.Value = 'lbm';
            app.RightListBoxLabel = uilabel(app.MassStiffnessTab);
            app.RightListBoxLabel.Text = 'Right';
            app.RightListBox = uilistbox(app.MassStiffnessTab);
            app.RightListBox.Items = {'lbf/in', 'lbf/ft', 'N/m', 'N/mm'};
            app.RightListBox.ValueChangedFcn = createCallbackFcn(app, @RightListBoxValueChanged, true);
            app.RightListBox.Value = 'lbf/in';
            app.OutputListBoxLabel = uilabel(app.MassStiffnessTab);
            app.OutputListBoxLabel.Text = 'Output';
            app.OutputListBox = uilistbox(app.MassStiffnessTab);
            app.OutputListBox.Items = {'natural frequency', 'mass', 'stiffness'};
            app.OutputListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.OutputListBox.Value = 'natural frequency';
            app.StaticDeflectionTab = uitab(app.TabGroup2);
            app.StaticDeflectionTab.Title = 'Static Deflection';
            app.StaticDeflectionTab.ButtonDownFcn = createCallbackFcn(app, @StaticDeflectionTabButtonDown, true);
            app.CalculateButton_3 = uibutton(app.StaticDeflectionTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.StaticDeflectionEditFieldLabel = uilabel(app.StaticDeflectionTab);
            app.StaticDeflectionEditFieldLabel.Text = 'Static Deflection';
            app.StaticDeflectionEditField = uieditfield(app.StaticDeflectionTab, 'text');
            app.UnitListBox = uilistbox(app.StaticDeflectionTab);
            app.UnitListBox.Items = {'inch', 'mm'};
            app.UnitListBox.Value = 'inch';
            app.FreeResponsetoInitialConditionsTab = uitab(app.TabGroup);
            app.FreeResponsetoInitialConditionsTab.SizeChangedFcn = createCallbackFcn(app, @FreeResponsetoInitialConditionsTabSizeChanged, true);
            app.FreeResponsetoInitialConditionsTab.Title = 'Free Response to Initial Conditions';
            app.FreeResponsetoInitialConditionsTab.ButtonDownFcn = createCallbackFcn(app, @FreeResponsetoInitialConditionsTabButtonDown, true);
            app.CalculateButton_2 = uibutton(app.FreeResponsetoInitialConditionsTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.UnitsListBoxLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.FreeResponsetoInitialConditionsTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.InitialDisplacementinEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.InitialDisplacementinEditFieldLabel.Text = 'Initial Displacement (in)';
            app.InitialDisplacementinEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.InitialDisplacementinEditField.ValueChangingFcn = createCallbackFcn(app, @InitialDisplacementinEditFieldValueChanging, true);
            app.InitialVelocityinsecEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.InitialVelocityinsecEditFieldLabel.Text = 'Initial Velocity (in/sec)';
            app.InitialVelocityinsecEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.InitialVelocityinsecEditField.ValueChangingFcn = createCallbackFcn(app, @InitialVelocityinsecEditFieldValueChanging, true);
            app.NumberofCyclesEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles';
            app.NumberofCyclesEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.NumberofCyclesEditField.Value = '20';
            app.DampingPercentEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.DampingPercentEditFieldLabel.Text = 'Damping Percent';
            app.DampingPercentEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.DampingPercentEditField.ValueChangingFcn = createCallbackFcn(app, @DampingPercentEditFieldValueChanging, true);
            app.DampingPercentEditField.Value = '5';
            app.MethodListBoxLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.MethodListBoxLabel.Text = 'Method';
            app.MethodListBox = uilistbox(app.FreeResponsetoInitialConditionsTab);
            app.MethodListBox.Items = {'Natural Frequency', 'Mass & Stiffness'};
            app.MethodListBox.ValueChangedFcn = createCallbackFcn(app, @MethodListBoxValueChanged, true);
            app.MethodListBox.Value = 'Natural Frequency';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.MasslbmEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.MasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @MasslbmEditFieldValueChanging, true);
            app.StiffnesslbfinEditFieldLabel = uilabel(app.FreeResponsetoInitialConditionsTab);
            app.StiffnesslbfinEditFieldLabel.Text = 'Stiffness (lbf/in)';
            app.StiffnesslbfinEditField = uieditfield(app.FreeResponsetoInitialConditionsTab, 'text');
            app.StiffnesslbfinEditField.ValueChangingFcn = createCallbackFcn(app, @StiffnesslbfinEditFieldValueChanging, true);
            app.Image = uiimage(app.sdoffnUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'spring_mass_system_2.jpg');
            app.ResultsTextAreaLabel = uilabel(app.sdoffnUIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.sdoffnUIFigure);
            app.ReturnButton = uibutton(app.sdoffnUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.sdoffnUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sdof_fn_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.sdoffnUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.sdoffnUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sdoffnUIFigure)
        end
    end
end