classdef sdof_response_various_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sdof_response_variousUIFigure   matlab.ui.Figure
        PerformButton                   matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        sdof_response_various_aver14byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SteadyStateTab                  matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        ExcitationFrequencyHzEditField  matlab.ui.control.EditField
        ExcitationFrequencyHzEditFieldLabel  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        BaseExcitationTab               matlab.ui.container.Tab
        BaseAccelerationGEditField      matlab.ui.control.EditField
        BaseAccelerationGEditFieldLabel  matlab.ui.control.Label
        CalculateButton_7               matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        AppliedForceTab_2               matlab.ui.container.Tab
        MasslbmEditField_2              matlab.ui.control.EditField
        MasslbmEditField_2Label         matlab.ui.control.Label
        AppliedForcelbfEditField        matlab.ui.control.EditField
        AppliedForcelbfEditFieldLabel   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Image2_3                        matlab.ui.control.Image
        AmplificationFactorQEditField_ss  matlab.ui.control.EditField
        AmplificationFactorQEditField_2Label  matlab.ui.control.Label
        NaturalFrequencyHzEditField_3   matlab.ui.control.EditField
        NaturalFrequencyHzEditField_3Label  matlab.ui.control.Label
        SteadyStateResponseResultsTextArea  matlab.ui.control.TextArea
        SteadyStateResponseResultsTextAreaLabel  matlab.ui.control.Label
        PeakSigmaTab                    matlab.ui.container.Tab
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzorpositiveslopezerocrossingrateEditFieldLabel  matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text_ax                         matlab.ui.control.Label
        text_percent                    matlab.ui.control.Label
        edit_ax                         matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateButton_2               matlab.ui.control.Button
        ExperimentTab                   matlab.ui.container.Tab
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        DurationsecEditField_exp        matlab.ui.control.EditField
        DurationsecEditField_2Label     matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        StdDevEditField                 matlab.ui.control.EditField
        StdDevEditFieldLabel            matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        AmplificationFactorQEditField_exp  matlab.ui.control.EditField
        AmplificationFactorQEditField_3Label  matlab.ui.control.Label
        NaturalFrequencyHzEditField_exp  matlab.ui.control.EditField
        NaturalFrequencyHzEditField_4Label  matlab.ui.control.Label
        CrestFactorResultsTextArea      matlab.ui.control.TextArea
        CrestFactorResultsTextAreaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        CalculateButton_3               matlab.ui.control.Button
        MilesTab                        matlab.ui.container.Tab
        AmplificationFactorQEditField_Miles  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyHzEditField_2   matlab.ui.control.EditField
        NaturalFrequencyHzEditField_2Label  matlab.ui.control.Label
        MilesResultsTextArea            matlab.ui.control.TextArea
        MilesResultsTextAreaLabel       matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        BaseAccelerationTab             matlab.ui.container.Tab
        PSDG2HzEditFieldLabel_2         matlab.ui.control.Label
        PSDG2HzEditField                matlab.ui.control.EditField
        PSDG2HzEditFieldLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        CalculateButton_4               matlab.ui.control.Button
        AppliedForceTab                 matlab.ui.container.Tab
        MasslbmEditField                matlab.ui.control.EditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        PSDlbf2HzEditField              matlab.ui.control.EditField
        PSDlbf2HzEditFieldLabel         matlab.ui.control.Label
        PSDG2HzEditFieldLabel_4         matlab.ui.control.Label
        InputTypeListBox_2              matlab.ui.control.ListBox
        InputTypeListBox_2Label         matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        CalculateButton_5               matlab.ui.control.Button
        AppliedPressureTab              matlab.ui.container.Tab
        ZerodBRef20microPaLabel         matlab.ui.control.Label
        InputTypeListBox                matlab.ui.control.ListBox
        InputTypeListBoxLabel           matlab.ui.control.Label
        SPLdBEditField                  matlab.ui.control.EditField
        SPLdBEditFieldLabel             matlab.ui.control.Label
        PSDG2HzEditFieldLabel_3         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        CalculateButton_6               matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        nPeak     % Description
        nPressure %
        nFM       %
        SUnit     %
    end
    
    methods (Access = private)

        function SteadyUnits(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.SUnit]=find_index(Items,im);

            if(app.SUnit==1)
                app.AppliedForcelbfEditFieldLabel.Text='Applied Force (lbf)';
                app.MasslbmEditField_2Label.Text='Mass (lbm)';
            else
                app.AppliedForcelbfEditFieldLabel.Text='Applied Force (N)';    
                app.MasslbmEditField_2Label.Text='Mass (kg)';          
            end
        end    

        function MF_Change(app)
            Items = char(app.InputTypeListBox_2.Items);
            im=app.InputTypeListBox_2.Value;
            [app.nFM]=find_index(Items,im);

            if(app.nFM==1)
                app.MasslbmEditFieldLabel.Text='Mass (lbm)';
            else
                app.MasslbmEditFieldLabel.Text='Mass (kg)';           
            end    
               
        end    

        function Pressure(app)
            Items = char(app.InputTypeListBox.Items);
            im=app.InputTypeListBox.Value;
            [app.nPressure]=find_index(Items,im);

            if(app.nPressure==1)
                app.SPLdBEditFieldLabel.Text='SPL (dB)';
            end
            if(app.nPressure==2)
                app.SPLdBEditFieldLabel.Text='PSD (Pa^2/Hz)';               
            end                      
            if(app.nPressure==3)
                app.SPLdBEditFieldLabel.Text='PSD (psi^2/Hz)';           
            end

        end    

        function listbox(app)

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nPeak]=find_index(Items,im);

            app.edit_ax.Visible='off';
            app.text_ax.Visible='off';
            app.text_percent.Visible='off';
            
            if(app.nPeak>=2)
                
                app.edit_ax.Visible='on';
                app.edit_ax.Enable='on';
                app.text_ax.Visible='on';
                app.text_percent.Visible='on';
            end
            
            if(app.nPeak==2)
                app.edit_ax.Value='63.2';
            end    
            if(app.nPeak==3)
                app.edit_ax.Value='50';
            end
        end       

        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.MilesResultsTextArea.Visible='off';
            app.MilesResultsTextAreaLabel.Visible='off';        
            app.SteadyStateResponseResultsTextArea.Visible='off';
            app.SteadyStateResponseResultsTextAreaLabel.Visible='off';
            app.CrestFactorResultsTextArea.Visible='off';
            app.CrestFactorResultsTextAreaLabel.Visible='off';
        end    

        function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end
        
        function AnalysisChange(app)
            ClosePlots(app);
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);
            try
                app.TabGroup.SelectedTab=app.TabGroup.Children(n);
            catch
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear(app);
            listbox(app);
            Pressure(app);
            MF_Change(app);
            SteadyUnits(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: PerformButton
        function PerformButtonPushed(app, event)
            AnalysisChange(app);
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
            AnalysisChange(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            disp('ref 1')

            m=app.TabGroup.SelectedTab;
            n=m.Title;

            if(contains(n,'Steady'))
                n=1;
            end              
            if(strcmp(n,'Peak Sigma'))
                n=2;
            end     
            if(strcmp(n,'Experiment'))
                n=3;
            end              
            if(strcmp(n,'Miles'))
                n=4;
            end 
            try
                app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
                app.listbox_choice=n;
            catch
                disp('SelectAnalysisListBox failed');
                app.SelectAnalysisListBox.Items
                n               
            end    
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            im=app.nPeak;

            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            T=str2num(app.DurationsecEditField.Value);
            
            [ps]=maximax_peak(fn,T);
            
            if(im>=2)
            
                ax=(1/100)*str2num(app.edit_ax.Value);
                [ps]=risk_overshoot(fn,T,ax);
            
            end
                        
            if(isinf(ps))
                warndlg(' (fn T) product is too high for stable calculation ');
                return;
            end

            str=sprintf('Expected Peak = %4.3g sigma',ps);
            
            tsec=T;

            sss=sprintf('Duration = %g sec',tsec);

            ss=sprintf('%s \n\n %s ',str,sss);
            
                tdays=floor(tsec/86400);
            
                residual=tsec-tdays*86400;
            
                thr=floor(residual/3600);
            
                residual=residual-thr*3600;
            
                tmin=floor(residual/60);
            
                residual=residual-tmin*60;
            
                tsec=residual;

                if(tmin>=1)
                    sss=sprintf('%g min %g sec',tmin,tsec);
                    ss=sprintf('%s \n\nDuration = %s',str,sss);
                end
                if(thr>=1)
                    sss=sprintf('%g hr %g min %g sec',(24*tdays+thr),tmin,tsec);
                    ss=sprintf('%s \n\nDuration = %s ',str,sss);
                end              
                 if(tdays>=1)
                    sss=sprintf('%d days %d hr %d min %g sec',tdays,thr,tmin,tsec);
                    ss=sprintf('%s \n\nDuration =  %s ',str,sss);                
                end           
                if(tdays>=365)
                    years=tdays/365.25;
                    sss=sprintf('%10.4g years',years);
                    ss=sprintf('%s \n\nDuration = %s ',str,sss);        
                end
            
            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
           
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            clear(app);
            listbox(app);
        end

        % Value changing function: edit_ax
        function edit_axValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function edit_fnValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
           clear(app);
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            Q=str2num(app.AmplificationFactorQEditField_Miles.Value);
            fn=str2num(app.NaturalFrequencyHzEditField_2.Value);
            P=str2num(app.PSDG2HzEditField.Value);

            x=sqrt((pi/2)*P*fn*Q);
            
            s1=sprintf('Acceleration: \n\n %8.3g GRMS \n',x);
            s2=sprintf(' %8.3g G 3-sigma \n\n',3*x);
   
            rts= 29.4*(1/fn^1.5)*sqrt((pi/2)*P*Q);
            
            r=rts/3;
            
            r1=sprintf('Relative Displacement: \n\n %8.3g inch RMS \n',r);
            r2=sprintf(' %8.3g inch 3-sigma \n\n',rts);

            
            rm1=sprintf('%8.3g mm RMS \n',25.4*r);
            rm2=sprintf('%8.3g mm 3-sigma',25.4*rts);
            
            ss=sprintf('%s %s %s %s %s %s \n',s1,s2,r1,r2,rm1,rm2);

            app.MilesResultsTextArea.Value=ss; 
            app.MilesResultsTextArea.Visible='on';
            app.MilesResultsTextAreaLabel.Visible='on'; 
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            Q=str2num(app.AmplificationFactorQEditField_Miles.Value);
            fn=str2num(app.NaturalFrequencyHzEditField_2.Value);
            P=str2num(app.PSDlbf2HzEditField.Value); 

            damp=1/(2*Q);
            omegan=2*pi*fn;

            mass=str2num(app.MasslbmEditField.Value);

            if(app.nFM==1)
                mass=mass/386;
            end    

            stiff=omegan^2*mass;

            a=sqrt(P/(8*damp));
            b=(1/mass)^(1/4);
            c=(1/stiff)^(3/4);

            x=a*b*c;

            v=omegan*x;

            FT=sqrt((pi/2)*P*fn*Q);

            if(app.nFM==1)
                ss=sprintf('Displacement= %7.3g inch RMS \n\nVelocity= %7.3g in/sec RMS \n\nTransmitted Force= %7.3g lbf RMS',x,v,FT);
            else
                ss=sprintf('Displacement= %7.3g mm RMS \n\nVelocity= %7.3g cm/sec RMS \n\nTransmitted Force= %7.3g N RMS',x*1000,v*100,FT);                                
            end

            app.MilesResultsTextArea.Value=ss; 
            app.MilesResultsTextArea.Visible='on';
            app.MilesResultsTextAreaLabel.Visible='on'; 
        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            Q=str2num(app.AmplificationFactorQEditField_Miles.Value);
            fn=str2num(app.NaturalFrequencyHzEditField_2.Value);
            P=str2num(app.SPLdBEditField.Value);

            if(isempty(fn))
                warndlg('Enter Natural Frequency')
                return;
            end    
            if(isempty(Q))
                warndlg('Enter Amplification Factor')
                return;
            end   
            if(isempty(P))
                warndlg('Enter Pressure')
                return;
            end   


            reference=20e-06;

            psi_per_Pa=0.000145038;
            
            if(app.nPressure==1)

                pressure_rms=reference*(10.^(P/20.) );
                df=fn*( 2^(1/6) - 1/(2^(1/6)) );
                P=pressure_rms^2/df;
            end 
            if(app.nPressure==1 || app.nPressure==3)
                x=sqrt((pi/2)*P*fn*Q);
                
                s0='Transmitted Pressure:';
                s1=sprintf(' %8.3g Pa RMS',x);
                s2=sprintf(' %8.3g Pa 3-sigma',3*x);     

                y=x*psi_per_Pa;

                s3=sprintf(' %8.3g psi RMS',y);
                s4=sprintf(' %8.3g psi 3-sigma',3*y);             

                
            end
            if(app.nPressure==3)
                x=sqrt((pi/2)*P*fn*Q);
                
                s0='Transmitted Pressure:';
                s1=sprintf(' %8.3g psi RMS',x);
                s2=sprintf(' %8.3g psi 3-sigma',3*x);  
  
                y=x/psi_per_Pa;

                s3=sprintf(' %8.3g Pa RMS',y);
                s4=sprintf(' %8.3g Pa 3-sigma',3*y);   
            end

            ss=sprintf('%s\n\n %s\n %s\n\n %s\n %s \n',s0,s1,s2,s3,s4);
            
            app.MilesResultsTextArea.Value=ss;      
            app.MilesResultsTextArea.Visible='on';
            app.MilesResultsTextAreaLabel.Visible='on'; 
        end

        % Value changing function: NaturalFrequencyHzEditField_2
        function NaturalFrequencyHzEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField_Miles
        function AmplificationFactorQEditField_MilesValueChanging(app, event)
            clear(app);
        end

        % Value changed function: InputTypeListBox
        function InputTypeListBoxValueChanged(app, event)
            clear(app);
            Pressure(app);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            clear(app);
        end

        % Value changed function: InputTypeListBox_2
        function InputTypeListBox_2ValueChanged(app, event)
            clear(app);
            MF_Change(app);
        end

        % Value changing function: PSDG2HzEditField
        function PSDG2HzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MasslbmEditField
        function MasslbmEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PSDlbf2HzEditField
        function PSDlbf2HzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SPLdBEditField
        function SPLdBEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear(app);

            disp('ref 1')
            app.AmplificationFactorQEditField_ss.Value
            
            nu=app.SUnit;

            fn=str2num(app.NaturalFrequencyHzEditField_3.Value);
            fext=str2num(app.ExcitationFrequencyHzEditField.Value);
            try
                Q=str2num(strtrim(app.AmplificationFactorQEditField_ss.Value));
            catch
                app.AmplificationFactorQEditField_ss.Value
                warndlg('Q error')
                return;
            end
   

            tpi=2*pi;
            omegan=tpi*fn;
            omega=tpi*fext;
            
            rho=omega/omegan;

            damp=1/(2*Q);
            
            den=omegan^2-omega^2+(1i)*(2*damp*omegan*omega);
            
            force=str2num(app.AppliedForcelbfEditField.Value);
            mass=str2num(app.MasslbmEditField_2.Value);
            
            if(nu==1)
                mass=mass/386;
            end
            
            stiff=mass*omegan^2;

            DD=1/den;
            ang=angle(DD);
            ph=(180/pi)*ang;
            
            displacement=force*(1/stiff)*omegan^2/den;
            velocity=(1i)*omega*displacement;
            acceleration=-omega^2*displacement;

            
            c=2*damp*omegan*mass;

            TT=abs(stiff*displacement + c*velocity);
            
            transmitted_force=force*TT;
           
            if(nu==1)
                acceleration=acceleration/386;
            else
                acceleration=acceleration/9.81;
                displacement=displacement*1000;
            end
            
            ss=sprintf('Frequency Ratio = %6.3g ',rho);
            as=sprintf('Acceleration = %7.3g G',abs(acceleration));

            if(nu==1)
                vs=sprintf('Velocity = %8.4g in/sec',abs(velocity));
                ds=sprintf('Displacement = %8.4g in ',abs(displacement));
                tf=sprintf('Trans Force = %8.4g lbf',transmitted_force);
                sk=sprintf('Stiffness = %8.4g lbf/in',stiff);
                ff='Force (lbf)';
            else
                vs=sprintf('Velocity = %8.4g m/sec',abs(velocity));
                ds=sprintf('Displacement = %8.4g mm',abs(displacement));
                tf=sprintf('Trans Force = %8.4g N',transmitted_force);    
                sk=sprintf('Stiffness = %8.4g N/m',stiff);
                ff='Force (N)';
            end    

            pa=sprintf('Displacement phase angle = %7.3g deg',ph);

            ss=sprintf('%s\n\n%s\n\n%s\n\n%s\n\n%s\n\n%s\n\n%s',ss,as,vs,ds,pa,tf,sk);
       
            app.SteadyStateResponseResultsTextArea.Value=ss;
            app.SteadyStateResponseResultsTextArea.Visible='on';
            app.SteadyStateResponseResultsTextAreaLabel.Visible='on';

            UU=force*cos(-ang);
            VV=force*sin(-ang);
            U=stiff*abs(displacement);
            V=c*abs(velocity);

            Lx=U;
            if(UU<0)
                Lx=U+abs(UU);
            end    

            Ly=V;

            Lmax=1.2*max([Lx Ly]);

            PP=Lmax/10;

            if(UU>=0)
              Lxmin=-PP;
              Lxmax=Lmax-PP;
            else
              Lxmin=UU-PP;
              Lxmax=Lxmin+Lmax;
            end    

            Lymin=-PP;
            Lymax=Lymin+Lmax;

            figure(1)
            hold on;
            %
            anArrow = annotation('arrow','Color','k') ;
            anArrow.Parent = gca;  % or any other existing axes or figure
            anArrow.Position = [0, 0, UU, VV] ;
            %
            anArrow = annotation('arrow','Color','b');
            anArrow.Parent = gca;  % or any other existing axes or figure
            anArrow.Position = [0, 0, U, 0] ;
            %
            anArrow = annotation('arrow','Color','r');
            anArrow.Parent = gca;  % or any other existing axes or figure
            anArrow.Position = [U, 0, 0, V] ;
            %     
            anArrow = annotation('arrow','Color',[0 0.5 0.5]);
            anArrow.Parent = gca;  % or any other existing axes or figure
            anArrow.Position = [U, V, -abs(U-UU), 0] ;
            UA=(U + U -abs(U-UU))/2;
            %
            text(-PP/2,V/2,'F','Color','k');
            text(U/2,-V/2,'kx','Color','b');
            text(U+PP/3,V/2,'cv','Color','r');
            text(UA,1.5*V,'ma','Color',[0 0.5 0.5]);
            %
            xlim([Lxmin Lxmax]);
            ylim([Lymin Lymax]);
            xlabel(ff);
            ylabel(ff);
            grid on;
            axis square;


            fns=(app.NaturalFrequencyHzEditField_3.Value);
            fexts=(app.ExcitationFrequencyHzEditField.Value);
            Qs=strtrim(app.AmplificationFactorQEditField_ss.Value);

           
            ss=sprintf(' Vector Diagram \n f=%s Hz, fn=%s Hz, Q=%s',fexts,fns,Qs);
            title(ss)
            hold off;

        end

        % Button pushed function: CalculateButton_7
        function CalculateButton_7Pushed(app, event)
            clear(app);
            
            nu=app.SUnit;

            fn=str2num(app.NaturalFrequencyHzEditField_3.Value);
            fext=str2num(app.ExcitationFrequencyHzEditField.Value);
            app.AmplificationFactorQEditField_Miles.Value
            Q=str2num(app.AmplificationFactorQEditField_ss.Value);
            base_accel=str2num(app.BaseAccelerationGEditField.Value);

            tpi=2*pi;
            omegan=tpi*fn;
            omega=tpi*fext;
            
            rho=omega/omegan;

            ss=sprintf('Frequency Ratio = %6.3g ',rho);
            
            damp=1/(2*Q);
            
            den=omegan^2-omega^2+(1i)*(2*damp*omegan*omega);
          
            if(nu==1)
                base_accel=base_accel*386;
            else
                base_accel=base_accel*9.81;
            end
            
            num=omegan^2+(1i)*2*damp*omega*omegan;
            
            acceleration=base_accel*num/den;
            velocity=acceleration/((1i)*omega);
            displacement=acceleration/(-omega^2);
            relative_displacement=-base_accel/den;

            z=num/den;
            
            ph=atan2(imag(z),real(z));
            phase_deg=180*ph/pi;

            if(nu==1)
                acceleration=acceleration/386;
            else
                acceleration=acceleration/9.81;
                displacement=displacement*1000;
                relative_displacement=relative_displacement*1000;
            end
            
            as=sprintf('Acceleration = %7.3g G',abs(acceleration));
            sp=sprintf('Accel Phase = %7.3g deg',phase_deg);
            ss=sprintf('%s\n\n%s',ss,as);
            ss=sprintf('%s\n\n%s',ss,sp);   
            
            if(nu==1)
                vs=sprintf('Velocity = %7.3g in/sec',abs(velocity));
                ds=sprintf('Displacement = %7.3g in',abs(displacement));
                rds=sprintf('Relative Disp = %7.3g in',abs(relative_displacement));             
            else
                vs=sprintf('Velocity = %7.3g m/sec',abs(velocity));    
                ds=sprintf('Displacement = %7.3g mm',abs(displacement));
                rds=sprintf('Relative Disp = %7.3g mm',abs(relative_displacement));             
            end

            ss=sprintf('%s\n\n%s',ss,vs);
            ss=sprintf('%s\n\n%s',ss,ds);
            ss=sprintf('%s\n\n%s',ss,rds);   
           
            app.SteadyStateResponseResultsTextArea.Value=ss;
            app.SteadyStateResponseResultsTextArea.Visible='on';
            app.SteadyStateResponseResultsTextAreaLabel.Visible='on';
        end

        % Value changing function: ExcitationFrequencyHzEditField
        function ExcitationFrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NaturalFrequencyHzEditField_3
        function NaturalFrequencyHzEditField_3ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField_ss
        function AmplificationFactorQEditField_ssValueChanging(app, event)
            clear(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            clear(app);
            SteadyUnits(app);
        end

        % Value changing function: BaseAccelerationGEditField
        function BaseAccelerationGEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AppliedForcelbfEditField
        function AppliedForcelbfEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MasslbmEditField_2
        function MasslbmEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            clear(app);

            fig_num=1;

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end
            
            num=str2num(app.NumberofTrialsEditField.Value);
            tmax=str2num(app.DurationsecEditField_exp.Value);
            sr=str2num(app.SampleRateHzEditField.Value);
            sigma=str2num(app.StdDevEditField.Value);
            
            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
              
            %%%    [a] = normrnd_function(sigma,np);
             
            clear length;
            np=length(TT);
             
            fn=str2num(app.NaturalFrequencyHzEditField_exp.Value);
             Q=str2num(app.AmplificationFactorQEditField_exp.Value);
            
            damp=1/(2*Q);
            
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,~,~,~,~,~]=srs_coefficients(fn,damp,dt); 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            order=6;
 
            fl=sr/10;
            Wn=fl/(sr/2);
            [bb,aa] = butter(order,Wn,'low');
                   
            ax=0.5;
            [ps]=risk_overshoot(fn,tmax,ax);
            
            crest=zeros(num,1);
            ssd=zeros(num,1);
            maxa=zeros(num,1);
            
            progressbar;
            
            for i=1:num    
            
                progressbar(i/num);
                
                a=randn(np,1);
            %
                a=fix_size(a);
            %
                a=a-mean(a);
                a=a*sigma/std(a);
            % 
                a=filter(bb,aa,a);
 
            %    scale for the std deviation
            %
                ave=mean(a);
                stddev=std(a);
                sss=sigma/stddev;
            %    
                a=(a-ave)*sss;        
                  
                [a_resp,a_pos,a_neg]=arbit_engine(a1,a2,b1,b2,b3,a);
                
                maxa(i)=max([ a_pos  a_neg  ]);
                crest(i)=maxa(i)/std(a_resp);
                ssd(i)=std(a_resp);

            end
            pause(0.2);
            progressbar(1);
            
            disp(' ');
            disp(' Crest Factor Results ');
            disp(' ');
            out1=sprintf(' Range: %8.4g to %8.4g \n',min(crest),max(crest));
            out2=sprintf(' mean = %8.4g    \n',mean(crest));
            out3=sprintf(' median = %8.4g  \n',median(crest));
            out4=sprintf(' std dev = %8.4g  \n',std(crest));
            out5=sprintf('\n Expected Peak = %8.4g sigma (Overshoot 50 percent) \n',ps);
            
            fprintf(out1);
            fprintf(out2);       
            fprintf(out3);

            fprintf(out4);
            fprintf(out5);       

            if(num>=2)
                ax=1/num;
                [ps]=risk_overshoot(fn,tmax,ax);
                out6=sprintf('\n Upper threshold for one crest factor point = %8.4g  (%5.3g percent) \n',ps,ax*100);
            end

            fprintf(out6);

            ss=sprintf('%s %s %s %s %s %s',out1,out2,out3,out4,out5,out6);
            
            disp(' ');
            disp(' Peak Acceleration Results (G)');
            disp(' ');
            fprintf(' Range: %8.4g to %8.4g \n',min(maxa),max(maxa));
            fprintf(' mean = %8.4g    \n',mean(maxa));
            fprintf(' median = %8.4g  \n',median(maxa));
            fprintf(' std dev = %8.4g  \n',std(maxa));
            
            nbars=num/100;
            if(nbars<=3000)
                nbars=31;
            end
            if(nbars<=100)
                nbars=20;
            end
            if(nbars<=10)
                nbars=3;
            end
            if(nbars<=1)
                nbars=1;
            end
            if(nbars>400)
                nbars=400;
            end
            
            figure(fig_num);
            fig_num=fig_num+1;
            histogram(ssd,nbars)
            ylabel('Counts');
            title('Histogram');
            xlabel('Standard Deviation');
            % qq=get(gca,'xlim');
            
            figure(fig_num);
            fig_num=fig_num+1;
            histogram(crest,nbars)
            ylabel('Counts');
            title('Histogram');
            xlabel('Crest Factor');
            % qq=get(gca,'xlim');
            
            app.CrestFactorResultsTextArea.Value=ss;
            app.CrestFactorResultsTextArea.Visible='on';
            app.CrestFactorResultsTextAreaLabel.Visible='on';
            
        end

        % Value changing function: NaturalFrequencyHzEditField_exp
        function NaturalFrequencyHzEditField_expValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField_exp
        function AmplificationFactorQEditField_expValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField
        function StdDevEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.sdof_response_variousUIFigure = uifigure('Visible', 'off');
            app.sdof_response_variousUIFigure.Name = 'sdof_response_various';
            app.ReturnButton = uibutton(app.sdof_response_variousUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.sdof_response_variousUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SteadyStateTab = uitab(app.TabGroup);
            app.SteadyStateTab.Title = 'Steady-State';
            app.SteadyStateResponseResultsTextAreaLabel = uilabel(app.SteadyStateTab);
            app.SteadyStateResponseResultsTextAreaLabel.Text = 'Steady-State Response Results';
            app.SteadyStateResponseResultsTextArea = uitextarea(app.SteadyStateTab);
            app.NaturalFrequencyHzEditField_3Label = uilabel(app.SteadyStateTab);
            app.NaturalFrequencyHzEditField_3Label.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField_3 = uieditfield(app.SteadyStateTab, 'text');
            app.NaturalFrequencyHzEditField_3.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_3ValueChanging, true);
            app.AmplificationFactorQEditField_2Label = uilabel(app.SteadyStateTab);
            app.AmplificationFactorQEditField_2Label.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_ss = uieditfield(app.SteadyStateTab, 'text');
            app.AmplificationFactorQEditField_ss.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditField_ssValueChanging, true);
            app.AmplificationFactorQEditField_ss.Value = '10';
            app.TabGroup3 = uitabgroup(app.SteadyStateTab);
            app.BaseExcitationTab = uitab(app.TabGroup3);
            app.BaseExcitationTab.Title = 'Base Excitation';
            app.Image_2 = uiimage(app.BaseExcitationTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.CalculateButton_7 = uibutton(app.BaseExcitationTab, 'push');
            app.CalculateButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_7Pushed, true);
            app.CalculateButton_7.Text = 'Calculate';
            app.BaseAccelerationGEditFieldLabel = uilabel(app.BaseExcitationTab);
            app.BaseAccelerationGEditFieldLabel.Text = 'Base Acceleration (G)';
            app.BaseAccelerationGEditField = uieditfield(app.BaseExcitationTab, 'text');
            app.BaseAccelerationGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelerationGEditFieldValueChanging, true);
            app.AppliedForceTab_2 = uitab(app.TabGroup3);
            app.AppliedForceTab_2.Title = 'Applied Force';
            app.Image2_3 = uiimage(app.AppliedForceTab_2);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.CalculateButton = uibutton(app.AppliedForceTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AppliedForcelbfEditFieldLabel = uilabel(app.AppliedForceTab_2);
            app.AppliedForcelbfEditFieldLabel.Text = 'Applied Force (lbf)';
            app.AppliedForcelbfEditField = uieditfield(app.AppliedForceTab_2, 'text');
            app.AppliedForcelbfEditField.ValueChangingFcn = createCallbackFcn(app, @AppliedForcelbfEditFieldValueChanging, true);
            app.MasslbmEditField_2Label = uilabel(app.AppliedForceTab_2);
            app.MasslbmEditField_2Label.Text = 'Mass (lbm)';
            app.MasslbmEditField_2 = uieditfield(app.AppliedForceTab_2, 'text');
            app.MasslbmEditField_2.ValueChangingFcn = createCallbackFcn(app, @MasslbmEditField_2ValueChanging, true);
            app.ExcitationFrequencyHzEditFieldLabel = uilabel(app.SteadyStateTab);
            app.ExcitationFrequencyHzEditFieldLabel.Text = {'Excitation'; 'Frequency (Hz)'};
            app.ExcitationFrequencyHzEditField = uieditfield(app.SteadyStateTab, 'text');
            app.ExcitationFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @ExcitationFrequencyHzEditFieldValueChanging, true);
            app.UnitsListBoxLabel = uilabel(app.SteadyStateTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SteadyStateTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.PeakSigmaTab = uitab(app.TabGroup);
            app.PeakSigmaTab.Title = 'Peak Sigma';
            app.CalculateButton_2 = uibutton(app.PeakSigmaTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.listbox_method = uilistbox(app.PeakSigmaTab);
            app.listbox_method.Items = {'Standard, Maximax, ERS, Average Maximum', 'Risk of Overshoot, XRS, URS', 'Risk of Overshoot 50%'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Risk of Overshoot 50%';
            app.text9 = uilabel(app.PeakSigmaTab);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Select Method';
            app.edit_ax = uieditfield(app.PeakSigmaTab, 'text');
            app.edit_ax.ValueChangingFcn = createCallbackFcn(app, @edit_axValueChanging, true);
            app.edit_ax.Tag = 'edit_ax';
            app.edit_ax.Value = '63.2';
            app.text_percent = uilabel(app.PeakSigmaTab);
            app.text_percent.Tag = 'text_percent';
            app.text_percent.Text = '%';
            app.text_ax = uilabel(app.PeakSigmaTab);
            app.text_ax.Tag = 'text_ax';
            app.text_ax.Text = {'Probability of '; 'Exceedance'};
            app.text14 = uilabel(app.PeakSigmaTab);
            app.text14.Tag = 'text14';
            app.text14.Text = 'ERS - Extreme Response Spectrum';
            app.text15 = uilabel(app.PeakSigmaTab);
            app.text15.Tag = 'text15';
            app.text15.Text = 'URS - Up-Crossing Risk Spectrum ';
            app.text16 = uilabel(app.PeakSigmaTab);
            app.text16.Tag = 'text16';
            app.text16.Text = 'XRS - X Response Spectrum';
            app.text17 = uilabel(app.PeakSigmaTab);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Acronyms:';
            app.ResultsTextAreaLabel = uilabel(app.PeakSigmaTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.PeakSigmaTab);
            app.DurationsecEditFieldLabel = uilabel(app.PeakSigmaTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.PeakSigmaTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.NaturalFrequencyHzorpositiveslopezerocrossingrateEditFieldLabel = uilabel(app.PeakSigmaTab);
            app.NaturalFrequencyHzorpositiveslopezerocrossingrateEditFieldLabel.Text = {'Natural Frequency (Hz)  '; '(or positive slope zero-crossing rate)'};
            app.NaturalFrequencyHzEditField = uieditfield(app.PeakSigmaTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.CalculateButton_3 = uibutton(app.ExperimentTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.Image_3 = uiimage(app.ExperimentTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.Label = uilabel(app.ExperimentTab);
            app.Label.Text = {'Band-limited White noise generation for base input and SDOF '; 'response to find crest factor distribution.   White noise is low-pass '; 'filtered at one-tenth of sample rate.'};
            app.CrestFactorResultsTextAreaLabel = uilabel(app.ExperimentTab);
            app.CrestFactorResultsTextAreaLabel.Text = 'Crest Factor Results';
            app.CrestFactorResultsTextArea = uitextarea(app.ExperimentTab);
            app.NaturalFrequencyHzEditField_4Label = uilabel(app.ExperimentTab);
            app.NaturalFrequencyHzEditField_4Label.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField_exp = uieditfield(app.ExperimentTab, 'text');
            app.NaturalFrequencyHzEditField_exp.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_expValueChanging, true);
            app.AmplificationFactorQEditField_3Label = uilabel(app.ExperimentTab);
            app.AmplificationFactorQEditField_3Label.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_exp = uieditfield(app.ExperimentTab, 'text');
            app.AmplificationFactorQEditField_exp.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditField_expValueChanging, true);
            app.AmplificationFactorQEditField_exp.Value = '10';
            app.NumberofTrialsEditFieldLabel = uilabel(app.ExperimentTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.ExperimentTab, 'text');
            app.NumberofTrialsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanging, true);
            app.StdDevEditFieldLabel = uilabel(app.ExperimentTab);
            app.StdDevEditFieldLabel.Text = 'Std Dev';
            app.StdDevEditField = uieditfield(app.ExperimentTab, 'text');
            app.StdDevEditField.ValueChangingFcn = createCallbackFcn(app, @StdDevEditFieldValueChanging, true);
            app.StdDevEditField.Value = '1';
            app.SampleRateHzEditFieldLabel = uilabel(app.ExperimentTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.ExperimentTab, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.SampleRateHzEditField.Value = '20000';
            app.DurationsecEditField_2Label = uilabel(app.ExperimentTab);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_exp = uieditfield(app.ExperimentTab, 'text');
            app.DurationsecEditField_exp.Value = '60';
            app.RandomNumberSeedListBoxLabel = uilabel(app.ExperimentTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.ExperimentTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.MilesTab = uitab(app.TabGroup);
            app.MilesTab.Title = 'Miles';
            app.TabGroup2 = uitabgroup(app.MilesTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.BaseAccelerationTab = uitab(app.TabGroup2);
            app.BaseAccelerationTab.Title = 'Base Acceleration';
            app.CalculateButton_4 = uibutton(app.BaseAccelerationTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.Image = uiimage(app.BaseAccelerationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.PSDG2HzEditFieldLabel = uilabel(app.BaseAccelerationTab);
            app.PSDG2HzEditFieldLabel.Text = 'PSD (G^2/Hz) ';
            app.PSDG2HzEditField = uieditfield(app.BaseAccelerationTab, 'text');
            app.PSDG2HzEditField.ValueChangingFcn = createCallbackFcn(app, @PSDG2HzEditFieldValueChanging, true);
            app.PSDG2HzEditFieldLabel_2 = uilabel(app.BaseAccelerationTab);
            app.PSDG2HzEditFieldLabel_2.Text = 'At Natural Frequency';
            app.AppliedForceTab = uitab(app.TabGroup2);
            app.AppliedForceTab.Title = 'Applied Force';
            app.CalculateButton_5 = uibutton(app.AppliedForceTab, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.Image2_2 = uiimage(app.AppliedForceTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.InputTypeListBox_2Label = uilabel(app.AppliedForceTab);
            app.InputTypeListBox_2Label.Text = 'Input Type';
            app.InputTypeListBox_2 = uilistbox(app.AppliedForceTab);
            app.InputTypeListBox_2.Items = {'PSD (lbf^2/Hz)', 'PSD (N^2/Hz)'};
            app.InputTypeListBox_2.ValueChangedFcn = createCallbackFcn(app, @InputTypeListBox_2ValueChanged, true);
            app.InputTypeListBox_2.Value = 'PSD (lbf^2/Hz)';
            app.PSDG2HzEditFieldLabel_4 = uilabel(app.AppliedForceTab);
            app.PSDG2HzEditFieldLabel_4.Text = 'At Natural Frequency';
            app.PSDlbf2HzEditFieldLabel = uilabel(app.AppliedForceTab);
            app.PSDlbf2HzEditFieldLabel.Text = 'PSD (lbf^2/Hz)';
            app.PSDlbf2HzEditField = uieditfield(app.AppliedForceTab, 'text');
            app.PSDlbf2HzEditField.ValueChangingFcn = createCallbackFcn(app, @PSDlbf2HzEditFieldValueChanging, true);
            app.MasslbmEditFieldLabel = uilabel(app.AppliedForceTab);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.AppliedForceTab, 'text');
            app.MasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @MasslbmEditFieldValueChanging, true);
            app.AppliedPressureTab = uitab(app.TabGroup2);
            app.AppliedPressureTab.Title = 'Applied Pressure';
            app.CalculateButton_6 = uibutton(app.AppliedPressureTab, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.Image2 = uiimage(app.AppliedPressureTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.PSDG2HzEditFieldLabel_3 = uilabel(app.AppliedPressureTab);
            app.PSDG2HzEditFieldLabel_3.Text = 'At Natural Frequency';
            app.SPLdBEditFieldLabel = uilabel(app.AppliedPressureTab);
            app.SPLdBEditFieldLabel.Text = 'SPL (dB)';
            app.SPLdBEditField = uieditfield(app.AppliedPressureTab, 'text');
            app.SPLdBEditField.ValueChangingFcn = createCallbackFcn(app, @SPLdBEditFieldValueChanging, true);
            app.InputTypeListBoxLabel = uilabel(app.AppliedPressureTab);
            app.InputTypeListBoxLabel.Text = 'Input Type';
            app.InputTypeListBox = uilistbox(app.AppliedPressureTab);
            app.InputTypeListBox.Items = {'One-Third Octave SPL (dB)', 'PSD (Pa^2/Hz)', 'PSD (psi^2/Hz)'};
            app.InputTypeListBox.ValueChangedFcn = createCallbackFcn(app, @InputTypeListBoxValueChanged, true);
            app.InputTypeListBox.Value = 'One-Third Octave SPL (dB)';
            app.ZerodBRef20microPaLabel = uilabel(app.AppliedPressureTab);
            app.ZerodBRef20microPaLabel.Text = 'Zero dB Ref = 20 micro Pa';
            app.MilesResultsTextAreaLabel = uilabel(app.MilesTab);
            app.MilesResultsTextAreaLabel.Text = 'Miles Results';
            app.MilesResultsTextArea = uitextarea(app.MilesTab);
            app.NaturalFrequencyHzEditField_2Label = uilabel(app.MilesTab);
            app.NaturalFrequencyHzEditField_2Label.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField_2 = uieditfield(app.MilesTab, 'text');
            app.NaturalFrequencyHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_2ValueChanging, true);
            app.AmplificationFactorQEditFieldLabel = uilabel(app.MilesTab);
            app.AmplificationFactorQEditFieldLabel.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_Miles = uieditfield(app.MilesTab, 'text');
            app.AmplificationFactorQEditField_Miles.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditField_MilesValueChanging, true);
            app.AmplificationFactorQEditField_Miles.Value = '10';
            app.sdof_response_various_aver14byTomIrvineLabel = uilabel(app.sdof_response_variousUIFigure);
            app.sdof_response_various_aver14byTomIrvineLabel.Text = 'sdof_response_various_a      ver 1.4   by Tom Irvine';
            app.SelectAnalysisListBoxLabel = uilabel(app.sdof_response_variousUIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.sdof_response_variousUIFigure);
            app.SelectAnalysisListBox.Items = {'Steady-State Sine Force or Acceleration Input', 'Peak Sigma for Random Base Input or Force', 'Peak Response Experiment', 'Miles Equation, Various'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Steady-State Sine Force or Acceleration Input';
            app.PerformButton = uibutton(app.sdof_response_variousUIFigure, 'push');
            app.PerformButton.ButtonPushedFcn = createCallbackFcn(app, @PerformButtonPushed, true);
            app.PerformButton.Text = 'Perform';
            % Show the figure after all components are created
            app.sdof_response_variousUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sdof_response_various_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.sdof_response_variousUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.sdof_response_variousUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sdof_response_variousUIFigure)
        end
    end
end