classdef sdof_transmissibility < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sdoftransUIFigure               matlab.ui.Figure
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        StepEditField                   matlab.ui.control.EditField
        StepEditFieldLabel              matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        FrequenciesHzLabel              matlab.ui.control.Label
        sdof_transmissibiltyver20byTomIrvineLabel  matlab.ui.control.Label
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BaseExcitationTab               matlab.ui.container.Tab
        TabGroup_base                   matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        MasslbmEditField_base           matlab.ui.control.EditField
        MasslbmLabel_base               matlab.ui.control.Label
        StiffnesslbfinEditField_base    matlab.ui.control.EditField
        StiffnesslbfinLabel_base        matlab.ui.control.Label
        InputMethodListBox_base         matlab.ui.control.ListBox
        InputMethodListBoxLabel_base    matlab.ui.control.Label
        NaturalFrequencyHzEditField_base  matlab.ui.control.EditField
        NaturalFreqHzLabel_base         matlab.ui.control.Label
        CalculateButton_base            matlab.ui.control.Button
        SaveTab                         matlab.ui.container.Tab
        FunctionListBox_base            matlab.ui.control.ListBox
        FunctionListBox_2Label          matlab.ui.control.Label
        OutputArrayNameEditField_base   matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton_base                 matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        AppliedForceTab                 matlab.ui.container.Tab
        TabGroup_force                  matlab.ui.container.TabGroup
        CalculateTab_force              matlab.ui.container.Tab
        InputMethodListBox_force        matlab.ui.control.ListBox
        InputMethodListBoxLabel         matlab.ui.control.Label
        StiffnesslbfinEditField_force   matlab.ui.control.EditField
        StiffnesslbfinLabel_force       matlab.ui.control.Label
        MasslbmEditField_force          matlab.ui.control.EditField
        MasslbmLabel                    matlab.ui.control.Label
        NaturalFrequencyHzEditField_force  matlab.ui.control.EditField
        NaturalFreqHzLabel_force        matlab.ui.control.Label
        CalculateButton_force           matlab.ui.control.Button
        SaveTab_2                       matlab.ui.container.Tab
        SaveFRFButton                   matlab.ui.control.Button
        FunctionListBox_force           matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        OutputArrayNameEditField_force  matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        iu      % Description
        iforce
        ibase
        admittance
        mobility
        accelerance     
        transmissibility
        complex_transmissibility
        power_transmissibility        
    end    

    methods (Access = private)


        function Method_base(app)
            Items = strtrim(app.InputMethodListBox_base.Items);
            im = strtrim(app.InputMethodListBox_base.Value);
            app.ibase = find(strcmp(Items, im));  

            if(app.ibase==1)
                app.StiffnesslbfinLabel_base.Visible='off';
                app.StiffnesslbfinEditField_base.Visible='off'; 
                app.MasslbmEditField_base.Visible='off'; 
                app.MasslbmLabel_base.Visible='off'; 
                app.NaturalFrequencyHzEditField_base.Visible='on';
                app.NaturalFreqHzLabel_base.Visible='on';
            else
                app.StiffnesslbfinLabel_base.Visible='on';
                app.StiffnesslbfinEditField_base.Visible='on';
                app.MasslbmEditField_base.Visible='on'; 
                app.MasslbmLabel_base.Visible='on'; 
                app.NaturalFrequencyHzEditField_base.Visible='off';
                app.NaturalFreqHzLabel_base.Visible='off';              
            end    
        end           


        function Method_force(app)
            Items = strtrim(app.InputMethodListBox_force.Items);
            im = strtrim(app.InputMethodListBox_force.Value);
            app.iforce = find(strcmp(Items, im));  

            if(app.iforce==1)
                app.StiffnesslbfinLabel_force.Visible='off';
                app.StiffnesslbfinEditField_force.Visible='off'; 
                app.NaturalFrequencyHzEditField_force.Visible='on';
                app.NaturalFreqHzLabel_force.Visible='on';
            else
                app.StiffnesslbfinLabel_force.Visible='on';
                app.StiffnesslbfinEditField_force.Visible='on';
                app.NaturalFrequencyHzEditField_force.Visible='off';
                app.NaturalFreqHzLabel_force.Visible='off';              
            end    
        end    

        function Units(app)

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));
          
            if(app.iu==1)
                app.MasslbmLabel.Text='Mass (lbm)';
                app.StiffnesslbfinLabel_force.Text='Stiffness (lbf/in)';
                app.MasslbmLabel_base.Text='Mass (lbm)';
                app.StiffnesslbfinLabel_base.Text='Stiffness (lbf/in)';               
            else
                app.MasslbmLabel.Text='Mass (kg)';     
                app.StiffnesslbfinLabel_force.Text='Stiffness (N/m)';
                app.MasslbmLabel_base.Text='Mass (kg)';     
                app.StiffnesslbfinLabel_base.Text='Stiffness (N/m)';                 
            end

        end    
        
        function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            Units(app);
            Method_force(app);
            Method_base(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Button pushed function: CalculateButton_force
        function CalculateButton_forcePushed(app, event)
            tpi=2*pi;
            
            Q=str2num(app.AmplificationFactorQEditField.Value);

            f1=str2num(app.MinEditField.Value);
            f2=str2num(app.MaxEditField.Value);
            df=str2num(app.StepEditField.Value);

            mass_orig=app.MasslbmEditField_force.Value;

            mass=str2num(mass_orig);

            if(app.iu==1)
                mass=mass/386;
            end

            if(app.iforce==1)
                fn=str2num(app.NaturalFrequencyHzEditField_force.Value);
                stiff=(tpi*fn)^2*mass;
            else
                stiff=str2num(app.StiffnesslbfinEditField_force.Value);
                fn=sqrt(stiff/mass)/tpi;
            end 

            omegan=tpi*fn;

            numfreq=1+floor((f2-f1)/df);

            freq=zeros(numfreq,1);
            
            app.admittance=zeros(numfreq,1);
            app.mobility=zeros(numfreq,1);
            app.accelerance=zeros(numfreq,1);
            
            admittance_mag=zeros(numfreq,1);
            mobility_mag=zeros(numfreq,1);
            accelerance_mag=zeros(numfreq,1);
            
            admittance_phase=zeros(numfreq,1);
            mobility_phase=zeros(numfreq,1);
            accelerance_phase=zeros(numfreq,1);
            
            %%%%
            
            omegan2=omegan^2;
            
            damp=1/(2*Q);

            for i=1:numfreq
                
                progressbar(i/numfreq);
                freq(i)=f1+(i-1)*df;
                omega=tpi*freq(i);
                
                den=(omegan2-omega^2)+1i*2*damp*omegan*omega;
                
                 app.admittance(i)=1/(mass*den);
                   app.mobility(i)=1i*omega*app.admittance(i);
                app.accelerance(i)=-omega^2*app.admittance(i);
                
            end
            
            if(app.iu==1)    
                app.accelerance=app.accelerance/386;       % G/lbf      
            else
                app.accelerance=app.accelerance/9.81;   % G/N
            end
            
            
            %%%%%%%%%%%%%%%%%%%%
            
            N=180/pi;
            
            for i=1:numfreq
            
                 H=app.admittance(i);
                 admittance_mag(i)=abs(H);
                 admittance_phase(i)=N*atan2(imag(H),real(H)); 
            
                 H=app.mobility(i);
                 mobility_mag(i)=abs(H);
                 mobility_phase(i)=N*atan2(imag(H),real(H));  
            
                 H=app.accelerance(i);
                 accelerance_mag(i)=abs(H);
                 accelerance_phase(i)=N*atan2(imag(H),real(H));
                 
            end 
            
               dynamic_stiffness=[freq 1./app.admittance ];
            mechanical_impedance=[freq 1./app.mobility ];
                    dynamic_mass=[freq 1./app.accelerance ];
            
             app.admittance=[freq app.admittance ];
               app.mobility=[freq app.mobility ];
            app.accelerance=[freq app.accelerance ];
            
            
            %%%%%%%%%%%%%%%%%%%%
            
            if(app.iu==1)
                Hd='(in/lbf)';
                Hv='(in/sec/lbf)';
                Ha='(G/lbf)';
                Hdd='(lbf/in)';
                Hvv='(lbf/in/sec)';
                Haa='(lbf/G)';   
            else
                Hd='(m/N)';
                Hv='(m/sec/N)';
                Ha='(G/N)';   
                Hdd='(N/m)';
                Hvv='(N/m/sec)';
                Haa='(N/G)';       
            end
            
            fig_num=1;
            
            ff=freq;
            fmin=f1;
            fmax=f2;
            
            md=4;
            
            %%%%%%%%%%%%%%%%%%%%

            FRF_m=admittance_mag;
            FRF_p=admittance_phase;
            
            ylab=sprintf('Magnitude %s ',Hd);
            
            if(app.iu==1)
                t_string=sprintf('Admittance  mass=%s lbm, stiff=%6.4g lbf/in, fn=%g Hz, Q=%g',mass_orig,stiff,fn,Q);
            else
                t_string=sprintf('Admittance  mass=%s kg, fn=%g Hz, Q=%g',mass_orig,fn,Q);    
            end
            
            [fig_num]=...
                plot_magnitude_phase_function(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
            
            
            %%%%%%%%%%%%%%%%%%%%
            
            FRF_m=mobility_mag;
            FRF_p=mobility_phase;
            
            ylab=sprintf('Magnitude %s ',Hv);
            
            if(app.iu==1)
                t_string=sprintf('Mobility  mass=%s lbm, stiff=%6.4g lbf/in, fn=%g Hz, Q=%g',mass_orig,stiff,fn,Q);
            else
                t_string=sprintf('Mobility  mass=%s kg, fn=%g Hz, Q=%g',mass_orig,fn,Q);    
            end
            
            [fig_num]=...
                plot_magnitude_phase_function(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
            
            %%%%%%%%%%%%%%%%%%%%
            
            FRF_m=accelerance_mag;
            FRF_p=accelerance_phase;
            
            ylab=sprintf('Magnitude %s ',Ha);
            
            if(app.iu==1)
                t_string=sprintf('Accelerance  mass=%s lbm, stiff=%6.4g lbf/in, fn=%g Hz, Q=%g',mass_orig,stiff,fn,Q);
            else
                t_string=sprintf('Accelerance  mass=%s kg, fn=%g Hz, Q=%g',mass_orig,fn,Q);    
            end
            
            [fig_num]=...
                plot_magnitude_phase_function(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
            
            %%%%%%%%%%%%%%%%%%%%
            
            fprintf('\n fn=%8.4g Hz ',fn);
            
            %%%
            
            f=freq;
            
            [~,I]=max([f admittance_mag]);
            xmax=admittance_mag(I(2));
            fmax=f(I(2));
            
            fprintf('\n Maximum admittance:  %8.4g Hz, %8.4g %s ',fmax,xmax,Hd);
            
            %%%%
            
            [~,I]=max([f mobility_mag]);
            xmax=mobility_mag(I(2));
            fmax=f(I(2));
            
            fprintf('\n Maximum mobility:  %8.4g Hz, %8.4g %s \n',fmax,xmax,Hv);
            
            %%%%
            
            [~,I]=max([f accelerance_mag]);
            xmax=accelerance_mag(I(2));
            fmax=f(I(2));
            
            fprintf('\n Maximum accelerance:  %8.4g Hz, %8.4g %s \n\n',fmax,xmax,Ha);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hp=figure(fig_num);
            fig_num=fig_num+1;
            
            [xtt,xTT,iflag]=xtick_label(f(1),f(end));
            
            subplot(2,3,1);
            plot(app.admittance(:,1),abs(app.admittance(:,2)));
            title('Admittance');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Hd));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            subplot(2,3,2);
            plot(app.mobility(:,1),abs(app.mobility(:,2)));
            title('Mobility');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Hv));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            subplot(2,3,3);
            plot(app.accelerance(:,1),abs(app.accelerance(:,2)));
            title('Accelerance');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Ha));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            subplot(2,3,4);
            plot(dynamic_stiffness(:,1),abs(dynamic_stiffness(:,2)));
            title('Dynamic Stiffness');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Hdd));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            subplot(2,3,5);
            plot(mechanical_impedance(:,1),abs(mechanical_impedance(:,2)));
            title('Mechanical Impedance');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Hvv));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            subplot(2,3,6);
            plot(dynamic_mass(:,1),abs(dynamic_mass(:,2)));
            title('Dynamic Mass');
            grid on;
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel(sprintf('Magnitude %s ',Haa));
            if(iflag==1)
                set(gca,'xtick',xtt);
                set(gca,'XTickLabel',xTT);
            end 
            
            
            if(app.iu==1)
                t_string=sprintf('SDOF Response  mass=%s lbm, stiff=%6.4g lbf/in, fn=%g Hz, Q=%g',mass_orig,stiff,fn,Q);
            else
                t_string=sprintf('mass=%s kg, fn=%g Hz, Q=%g',mass_orig,fn,Q);    
            end
            
            sgt=sgtitle(t_string);
            sgt.FontSize = 11;
            
            set(hp, 'Position', [0 0 1200 650]);


        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ClosePlots(app);
            Units(app);
        end

        % Value changing function: NaturalFrequencyHzEditField_force
        function NaturalFrequencyHzEditField_forceValueChanging(app, event)
            
            ClosePlots(app);
        end

        % Value changing function: MasslbmEditField_force
        function MasslbmEditField_forceValueChanging(app, event)
           
            ClosePlots(app);
        end

        % Button pushed function: CalculateButton_base
        function CalculateButton_basePushed(app, event)
        

            tpi=2*pi;
            
            Q=str2num(app.AmplificationFactorQEditField.Value);

            f1=str2num(app.MinEditField.Value);
            f2=str2num(app.MaxEditField.Value);
            df=str2num(app.StepEditField.Value);


            if(app.ibase==1)
                fn=str2num(app.NaturalFrequencyHzEditField_base.Value);

            else
                stiff=str2num(app.StiffnesslbfinEditField_base.Value);
                mass=str2num(app.MasslbmEditField_base.Value);

                if(app.iu==1)
                    mass=mass/386;
                end

                fn=sqrt(stiff/mass)/tpi;
            end 

            numfreq=1+floor((f2-f1)/df);

            freq=zeros(numfreq,1);
            
            %%%%%%%%%%%%
            
            a_trans=zeros(numfreq,1);
            a_trans_mag=zeros(numfreq,1);
            ap_trans=zeros(numfreq,1);
            
            rd_trans_mag=zeros(numfreq,1);
            
            damp=1/(2*Q);
            
            progressbar;
            
            for i=1:numfreq
                
                progressbar(i/numfreq);
                freq(i)=f1+(i-1)*df;
                
                [accel_complex,rd_complex,accel_mag,rd_mag]=...
                                                      sdof_trans_base(freq(i),fn,damp);
                
                a_trans(i)=accel_complex;
                a_trans_mag(i)=accel_mag;
                ap_trans(i)=(accel_mag)^2;
                
                rd_trans_mag(i)=rd_mag;
                 
            end
            pause(0.4);
            progressbar(1);
            
            %%%%%%%%%%%%%%%%%%%%
            
            app.transmissibility=[freq a_trans_mag];
            
            app.power_transmissibility=[freq ap_trans];
            app.complex_transmissibility=[freq real(a_trans) imag(a_trans)];
            
            %%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            if(app.ibase==1)
                t_string=sprintf('Acceleration Transmissibility  fn=%g Hz, Q=%g',fn,Q);
            else
                t_string=sprintf('Acceleration Transmissibility  fn=%7.3g Hz, Q=%g',fn,Q);    
            end
            y_label='Trans (G/G)';
            x_label='Frequency (Hz)';
            
            [fig_num]=plot_loglog_function(fig_num,x_label,y_label,t_string,app.transmissibility,f1,f2);     
                 
            %
            %%%%%%%%%%%%%%%%%%%%
                 
            xlabel2=x_label;
            
            t_string1=t_string;
            t_string2='';
            
            ylabel1='Real Trans (G/G)';
            ylabel2='Imag Trans (G/G)';
            
            data1=[freq real(a_trans)];
            data2=[freq imag(a_trans)];
            
            [fig_num]=subplots_two_loglin_two_titles(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %%%%%%%%%%%%%%%%%%%%
            
            if(app.ibase==1)
                t_string=sprintf('Acceleration Power Trans  fn=%g Hz, Q=%g',fn,Q);
            else
                t_string=sprintf('Acceleration Power Trans  fn=%7.3g Hz, Q=%g',fn,Q);    
            end
            y_label='Trans (G^2/G^2)';
            x_label='Frequency (Hz)';
            
            md=6;

            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,app.power_transmissibility,f1,f2,md);        
            
            %%%%%%%%%%%%%%%%%%%%
            
            if(app.ibase==1)
                t_string=sprintf('Relative Displacement Transmissibility  fn=%g Hz, Q=%g',fn,Q);
            else
                t_string=sprintf('Relative Displacement Transmissibility  fn=%7.3g Hz, Q=%g',fn,Q);    
            end
            
            
            if(app.iu==1)
                rd_transmissibility=[freq rd_trans_mag*386];
                y_label='Trans (in/G)';
            else
                rd_transmissibility=[freq rd_trans_mag*9.81];
                y_label='Trans (m/G)';    
            end
            
            x_label='Frequency (Hz)';
            
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,rd_transmissibility,f1,f2,md);     
                 
            %
            %%%%%%%%%%%%%%%%%%%%
            
            out1=sprintf('\n fn=%8.4g Hz ',fn);
            disp(out1);
            
            
            [~,I]=max(app.transmissibility);
            xmax=app.transmissibility(I(2),2);
            fmax=app.transmissibility(I(2),1);
            
            out1=sprintf('\n Maximum Transmissibility:  %8.4g Hz, %8.4g (G/G) ',fmax,xmax);
            disp(out1);

        end

        % Value changed function: InputMethodListBox_force
        function InputMethodListBox_forceValueChanged(app, event)
            
            Method_force(app);
        end

        % Callback function
        function SaveButton_forcePushed(app, event)


        end

        % Button pushed function: SaveFRFButton
        function SaveFRFButtonPushed(app, event)
            Items = strtrim(app.FunctionListBox_force.Items);
            im = strtrim(app.FunctionListBox_force.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.admittance;
            end
            if(n==2)
                data=app.mobility;
            end
            if(n==3)
                data=app.accelerance;
            end

            output_name=app.OutputArrayNameEditField_force.Value;
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete');
        end

        % Button pushed function: SaveButton_base
        function SaveButton_basePushed(app, event)
            Items = strtrim(app.FunctionListBox_base.Items);
            im = strtrim(app.FunctionListBox_base.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.transmissibility;
            end
            if(n==2)
                data=app.complex_transmissibility;
            end
            if(n==3)
                data=app.power_transmissibility;
            end

            output_name=app.OutputArrayNameEditField_base.Value;
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete');
        end

        % Value changed function: InputMethodListBox_base
        function InputMethodListBox_baseValueChanged(app, event)
            
            Method_base(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.sdoftransUIFigure = uifigure('Visible', 'off');
            app.sdoftransUIFigure.Name = 'sdof trans';
            app.TabGroup = uitabgroup(app.sdoftransUIFigure);
            app.BaseExcitationTab = uitab(app.TabGroup);
            app.BaseExcitationTab.Title = 'Base Excitation';
            app.Image = uiimage(app.BaseExcitationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.TabGroup_base = uitabgroup(app.BaseExcitationTab);
            app.CalculateTab = uitab(app.TabGroup_base);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton_base = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_base.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_basePushed, true);
            app.CalculateButton_base.Text = 'Calculate';
            app.NaturalFreqHzLabel_base = uilabel(app.CalculateTab);
            app.NaturalFreqHzLabel_base.Text = 'Natural Freq (Hz)';
            app.NaturalFrequencyHzEditField_base = uieditfield(app.CalculateTab, 'text');
            app.InputMethodListBoxLabel_base = uilabel(app.CalculateTab);
            app.InputMethodListBoxLabel_base.Text = 'Input Method';
            app.InputMethodListBox_base = uilistbox(app.CalculateTab);
            app.InputMethodListBox_base.Items = {'Natural Frequency', 'Mass & Stiffness'};
            app.InputMethodListBox_base.ValueChangedFcn = createCallbackFcn(app, @InputMethodListBox_baseValueChanged, true);
            app.InputMethodListBox_base.Value = 'Natural Frequency';
            app.StiffnesslbfinLabel_base = uilabel(app.CalculateTab);
            app.StiffnesslbfinLabel_base.Text = 'Stiffness (lbf/in)';
            app.StiffnesslbfinEditField_base = uieditfield(app.CalculateTab, 'text');
            app.MasslbmLabel_base = uilabel(app.CalculateTab);
            app.MasslbmLabel_base.Text = 'Mass (lbm)';
            app.MasslbmEditField_base = uieditfield(app.CalculateTab, 'text');
            app.SaveTab = uitab(app.TabGroup_base);
            app.SaveTab.Title = 'Save';
            app.SaveButton_base = uibutton(app.SaveTab, 'push');
            app.SaveButton_base.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_basePushed, true);
            app.SaveButton_base.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField_base = uieditfield(app.SaveTab, 'text');
            app.FunctionListBox_2Label = uilabel(app.SaveTab);
            app.FunctionListBox_2Label.Text = 'Function';
            app.FunctionListBox_base = uilistbox(app.SaveTab);
            app.FunctionListBox_base.Items = {'freq & accel transmissibility magnitude       ', 'freq & accel transmissibility real & imaginary', 'freq & accel power transmissibility '};
            app.FunctionListBox_base.Value = 'freq & accel transmissibility magnitude       ';
            app.AppliedForceTab = uitab(app.TabGroup);
            app.AppliedForceTab.Title = 'Applied Force';
            app.Image_2 = uiimage(app.AppliedForceTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.TabGroup_force = uitabgroup(app.AppliedForceTab);
            app.CalculateTab_force = uitab(app.TabGroup_force);
            app.CalculateTab_force.Title = 'Calculate';
            app.CalculateButton_force = uibutton(app.CalculateTab_force, 'push');
            app.CalculateButton_force.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_forcePushed, true);
            app.CalculateButton_force.Text = 'Calculate';
            app.NaturalFreqHzLabel_force = uilabel(app.CalculateTab_force);
            app.NaturalFreqHzLabel_force.Text = 'Natural Freq (Hz)';
            app.NaturalFrequencyHzEditField_force = uieditfield(app.CalculateTab_force, 'text');
            app.NaturalFrequencyHzEditField_force.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_forceValueChanging, true);
            app.MasslbmLabel = uilabel(app.CalculateTab_force);
            app.MasslbmLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField_force = uieditfield(app.CalculateTab_force, 'text');
            app.MasslbmEditField_force.ValueChangingFcn = createCallbackFcn(app, @MasslbmEditField_forceValueChanging, true);
            app.StiffnesslbfinLabel_force = uilabel(app.CalculateTab_force);
            app.StiffnesslbfinLabel_force.Text = 'Stiffness (lbf/in)';
            app.StiffnesslbfinEditField_force = uieditfield(app.CalculateTab_force, 'text');
            app.InputMethodListBoxLabel = uilabel(app.CalculateTab_force);
            app.InputMethodListBoxLabel.Text = 'Input Method';
            app.InputMethodListBox_force = uilistbox(app.CalculateTab_force);
            app.InputMethodListBox_force.Items = {'fn & Mass', 'Mass & Stiffness'};
            app.InputMethodListBox_force.ValueChangedFcn = createCallbackFcn(app, @InputMethodListBox_forceValueChanged, true);
            app.InputMethodListBox_force.Value = 'fn & Mass';
            app.SaveTab_2 = uitab(app.TabGroup_force);
            app.SaveTab_2.Title = 'Save';
            app.OutputArrayNameEditField_2Label = uilabel(app.SaveTab_2);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_force = uieditfield(app.SaveTab_2, 'text');
            app.FunctionListBoxLabel = uilabel(app.SaveTab_2);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox_force = uilistbox(app.SaveTab_2);
            app.FunctionListBox_force.Items = {'freq & admittance complex', 'freq & mobility complex', 'freq & accelerance complex '};
            app.FunctionListBox_force.Value = 'freq & admittance complex';
            app.SaveFRFButton = uibutton(app.SaveTab_2, 'push');
            app.SaveFRFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFRFButtonPushed, true);
            app.SaveFRFButton.Text = 'Save FRF';
            app.ReturnButton = uibutton(app.sdoftransUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.AmplificationFactorQEditFieldLabel = uilabel(app.sdoftransUIFigure);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.sdoftransUIFigure, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.sdof_transmissibiltyver20byTomIrvineLabel = uilabel(app.sdoftransUIFigure);
            app.sdof_transmissibiltyver20byTomIrvineLabel.Text = 'sdof_transmissibilty   ver 2.0   by Tom Irvine';
            app.FrequenciesHzLabel = uilabel(app.sdoftransUIFigure);
            app.FrequenciesHzLabel.Text = 'Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.sdoftransUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.sdoftransUIFigure, 'text');
            app.MinEditField.Value = '10';
            app.MaxEditFieldLabel = uilabel(app.sdoftransUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.sdoftransUIFigure, 'text');
            app.MaxEditField.Value = '2000';
            app.StepEditFieldLabel = uilabel(app.sdoftransUIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.sdoftransUIFigure, 'text');
            app.StepEditField.Value = '0.5';
            app.UnitsListBoxLabel = uilabel(app.sdoftransUIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.sdoftransUIFigure);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            % Show the figure after all components are created
            app.sdoftransUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sdof_transmissibility

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.sdoftransUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.sdoftransUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sdoftransUIFigure)
        end
    end
end