classdef shock_propagation_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        shockpropagationUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DistanceJointAttenuationTab     matlab.ui.container.Tab
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        SourceSRSTab                    matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        Add50ipsLineListBox             matlab.ui.control.ListBox
        Add50ipsLineListBoxLabel        matlab.ui.control.Label
        SRSPlotTitleEditField           matlab.ui.control.EditField
        SRSPlotTitleEditFieldLabel      matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel  matlab.ui.control.Label
        MartinMariettaTab               matlab.ui.container.Tab
        DoubleclickonTypecolumncellstoaccesspulldownmenuLabel  matlab.ui.control.Label
        SetRampAttenuationtoPlateauAttenuationListBox  matlab.ui.control.ListBox
        SetRampAttenuationtoPlateauAttenuationListBoxLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ResetButton                     matlab.ui.control.Button
        NumberofAttenuationStepsListBox  matlab.ui.control.ListBox
        NumberofAttenuationStepsListBoxLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        NASAHDBK7005Tab                 matlab.ui.container.Tab
        DoubleclickonTypecolumncellstoaccesspulldownmenuLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        NumberofAttenuationStepsListBox_7005  matlab.ui.control.ListBox
        NumberofAttenuationStepsListBox_2Label  matlab.ui.control.Label
        ResetButton_7005                matlab.ui.control.Button
        CalculateButton_7005            matlab.ui.control.Button
        ResultsExportTab                matlab.ui.container.Tab
        AttenautedSRSNameEditField      matlab.ui.control.EditField
        AttenautedSRSNameEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SRSResultsTextArea              matlab.ui.control.TextArea
        SRSResultsTextAreaLabel         matlab.ui.control.Label
        DistanceJointWaveletMethodTab   matlab.ui.container.Tab
        SelectInputArrayListBox_wavelet  matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateTableButton               matlab.ui.control.Button
        ThewavelettablemusthavefourcolumnsLabel  matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        SemiinfiniteBeamAnalogyTab_2    matlab.ui.container.Tab
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        ThicknessinEditField            matlab.ui.control.EditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        WidthinEditField                matlab.ui.control.EditField
        WidthinEditFieldLabel           matlab.ui.control.Label
        DistancefromSourceinEditField   matlab.ui.control.EditField
        DistancefromSourceinEditFieldLabel  matlab.ui.control.Label
        DampingPercentEditField         matlab.ui.control.EditField
        DampingPercentEditFieldLabel    matlab.ui.control.Label
        UnitsListBox_2                  matlab.ui.control.ListBox
        UnitsListBox_2Label             matlab.ui.control.Label
        CalculateButton_beam            matlab.ui.control.Button
        JointWaveletTable_2             matlab.ui.container.Tab
        DownwardSlopedBoctEditField     matlab.ui.control.EditField
        DownwardSlopedBoctLabel         matlab.ui.control.Label
        MaximumUnityGainFrequencyHzEditField  matlab.ui.control.EditField
        MaximumUnityGainFrequencyHzLabel  matlab.ui.control.Label
        CalculateButton_joint_wavelet   matlab.ui.control.Button
        ViewResultsExportTab            matlab.ui.container.Tab
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        StructureBorneWavePropagationTab  matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        DiagramTab                      matlab.ui.container.Tab
        Longitudinalwaveimpedancechangever10byTomIrvineLabel_2  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MateralPropertiesTab            matlab.ui.container.Tab
        Material1Panel                  matlab.ui.container.Panel
        CrossSectionAreain2EditField_m1  matlab.ui.control.EditField
        CrossSectionAreain2EditFieldLabel_m1  matlab.ui.control.Label
        ElasticModuluspsiEditField_m1   matlab.ui.control.EditField
        ElasticModuluspsiEditField_m1Label  matlab.ui.control.Label
        MassDensitylbmin3EditField_m1   matlab.ui.control.EditField
        MassDensitylbmin3EditField_m1Label  matlab.ui.control.Label
        Material1ListBox                matlab.ui.control.ListBox
        Material1ListBoxLabel           matlab.ui.control.Label
        Material2Panel                  matlab.ui.container.Panel
        CrossSectionAreain2EditField_m2  matlab.ui.control.EditField
        CrossSectionAreain2EditFieldLabel_m2  matlab.ui.control.Label
        ElasticModuluspsiEditField_m2   matlab.ui.control.EditField
        ElasticModuluspsiEditField_m2Label  matlab.ui.control.Label
        MassDensitylbmin3EditField_m2   matlab.ui.control.EditField
        MassDensitylbmin3EditField_m2Label  matlab.ui.control.Label
        Material2ListBox                matlab.ui.control.ListBox
        Material2ListBoxLabel           matlab.ui.control.Label
        UnitsListBox_longitudinal_wave  matlab.ui.control.ListBox
        UnitsListBox_3Label             matlab.ui.control.Label
        CalculationTab                  matlab.ui.container.Tab
        ResultsTextArea_long            matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        CalculateButton_long            matlab.ui.control.Button
        Longitudinalwaveimpedancechangever10byTomIrvineLabel  matlab.ui.control.Label
        shock_propagation_aver11byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        nsteps % Description
        iu
        beam_iu
        atten_srs
        Ain
        srs_in
        Atr
        srs_tr
        new_wavelet_table
        imat
        E
        rho
        long_iu
    end
    
    methods (Access = private)

        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end

        function UnitsLong(app)  

            Items = strtrim(app.UnitsListBox_longitudinal_wave.Items);
            im = strtrim(app.UnitsListBox_longitudinal_wave.Value);
            app.long_iu = find(strcmp(Items, im));

            if(app.long_iu==1)
                app.ElasticModuluspsiEditField_m1Label.Text='Elastic Modulus (psi)';
                app.MassDensitylbmin3EditField_m1Label.Text='Mass Density  (lbm/in^3)';
                app.CrossSectionAreain2EditFieldLabel_m1.Text='Cross-section Area (in^2)';
                app.ElasticModuluspsiEditField_m2Label.Text='Elastic Modulus (psi)';
                app.MassDensitylbmin3EditField_m2Label.Text='Mass Density  (lbm/in^3)';
                app.CrossSectionAreain2EditFieldLabel_m2.Text='Cross-section Area (in^2)';
            else
                app.ElasticModuluspsiEditField_m1Label.Text='Elastic Modulus (GPa)';
                app.MassDensitylbmin3EditField_m1Label.Text='Mass Density  (kg/m^3)';
                app.CrossSectionAreain2EditFieldLabel_m1.Text='Cross-section Area (mm^2)';             
                app.ElasticModuluspsiEditField_m2Label.Text='Elastic Modulus (GPa)';
                app.MassDensitylbmin3EditField_m2Label.Text='Mass Density  (kg/m^3)';
                app.CrossSectionAreain2EditFieldLabel_m2.Text='Cross-section Area (mm^2)';
            end

            Items = strtrim(app.Material1ListBox.Items);
            im = strtrim(app.Material1ListBox.Value);
            imat1 = find(strcmp(Items, im));
            
            [elastic_modulus,mass_density,~]=six_materials(app.long_iu,imat1);
             
            if(imat1<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
            else
                    ss1=' ';
                    ss2=' ';
            end
             
            app.ElasticModuluspsiEditField_m1.Value=ss1;
            app.MassDensitylbmin3EditField_m1.Value=ss2;

            %%%%%%%%
            
            Items = strtrim(app.Material2ListBox.Items);
            im = strtrim(app.Material2ListBox.Value);
            imat2 = find(strcmp(Items, im));
            
            [elastic_modulus,mass_density,~]=six_materials(app.long_iu,imat2);
             
            if(imat2<=6)
                    ss1=sprintf('%8.4g',elastic_modulus);
                    ss2=sprintf('%8.4g',mass_density);
            else
                    ss1=' ';
                    ss2=' ';
            end
             
            app.ElasticModuluspsiEditField_m2.Value=ss1;
            app.MassDensitylbmin3EditField_m2.Value=ss2;
        end

        function beam_units(app)
            Items = strtrim(app.UnitsListBox_2.Items);
            im = strtrim(app.UnitsListBox_2.Value);
            app.beam_iu = find(strcmp(Items, im));

            if(app.beam_iu==1)
                app.DistancefromSourceinEditFieldLabel.Text='Distance From Source (in)';
                app.WidthinEditFieldLabel.Text='Width (in)';
                app.ThicknessinEditFieldLabel.Text='Thickness (in)';
            else
                app.DistancefromSourceinEditFieldLabel.Text='Distance From Source (cm)';          
                app.WidthinEditFieldLabel.Text='Width (mm)';
                app.ThicknessinEditFieldLabel.Text='Thickness (mm)';            
            end 

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.beam_iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  


        end    

        function units(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            MM_Change_Rows(app);
            N7005_Change_Rows(app);
        end

        function N7005_Change_Rows(app)

            app.nsteps = str2num(app.NumberofAttenuationStepsListBox_7005.Value);

            Nrows=app.nsteps; 

            A=app.UITable_2.Data;

            Ncolumns=3;
            
            data_s = cell(Nrows, 3);
            
            if(~isempty(A))
            
                sz=size(A);
                Arows=sz(1);
            
                M=min([ Arows Nrows ]);
            
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end
                end
            
            end
            
            if(app.iu==1)
                cn={'Type','Delta Distance (in)','Plateau Remaining Ratio'};
            else
                cn={'Type','Delta Distance (m)','Plateau Remaining Ratio'};
            end
            app.UITable_2.ColumnName=cn;
            
            try
                app.UITable_2.Data=data_s;
            catch
            end

        end
        function MM_Change_Rows(app)

            app.nsteps = str2num(app.NumberofAttenuationStepsListBox.Value);

            Nrows=app.nsteps; 

            A=app.UITable.Data;

            Ncolumns=3;
            
            data_s = cell(Nrows, 3);
            
            if(~isempty(A))
            
                sz=size(A);
                Arows=sz(1);
            
                M=min([ Arows Nrows ]);
            
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end
                end
            
            end
            
            if(app.iu==1)
                cn={'Type','Delta Distance (in)','Plateau Remaining Ratio'};
            else
                cn={'Type','Delta Distance (m)','Plateau Remaining Ratio'};
            end
            app.UITable.ColumnName=cn;
            
            try
                app.UITable.Data=data_s;
            catch
            end

        end

        function update_srs(app)
            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
        end 
        function update_wavelet(app)
            cell_array=update_wavelet();
            app.SelectInputArrayListBox_wavelet.Items=unique(sort(cell_array));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.NumberofAttenuationStepsListBox.Value='1';            
            app.NumberofAttenuationStepsListBox_7005.Value='1';

            app.UITable.ColumnFormat = {{'Cylindrical Shell' 'Longeron or Stringer' 'Skin/Ring Frame' 'Primary Truss' 'Complex Airframe' 'Complex Equipment Structure' 'Honeycomb' 'Joint'} 'char' 'char'};
            app.UITable.ColumnName = {'Type'; 'Delta Distance (in)'; 'Plateau Remaining Ratio'};
            app.UITable.ColumnWidth = {200, 130, 170};
            app.UITable.ColumnEditable = [true true true];
            
            app.UITable_2.ColumnFormat = {{'Distance' 'Joint'} 'char' 'char'};            
            
            CloseFigures(app);
            update_srs(app);
            update_wavelet(app);
            units(app);
            beam_units(app);
            UnitsLong(app);

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            CloseFigures(app);

            Items = strtrim(app.SetRampAttenuationtoPlateauAttenuationListBox.Items);
            im = strtrim(app.SetRampAttenuationtoPlateauAttenuationListBox.Value);
            nrp = find(strcmp(Items, im));
 
            Items = strtrim(app.Add50ipsLineListBox.Items);
            im = strtrim(app.Add50ipsLineListBox.Value);
            CV = find(strcmp(Items, im));

            app.nsteps = str2num(app.NumberofAttenuationStepsListBox.Value);
     
            A=app.UITable.Data;

            if(~isempty(A))
            
                sz=size(A);
                Arows=sz(1);
            
                for i=1:sz(1)
                    for j=1:sz(2)
                        data_s{i,j}=A{i,j};
                    end
                end
            
            end

            % Ncolumns=3;
            Nrows=app.nsteps;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            md=5;
            
            t_string=app.SRSPlotTitleEditField.Value;
            
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            
            srs1=THM;
            f=THM(:,1);
       
            %%%%%%%%%%%%%%%%%%%

            ss=sprintf(' Source SRS \n   fn(Hz)  Accel(G) \n');
            
            for i=1:length(f)
                out1=sprintf(' %6.0f  %8.4g ',srs1(i,1),srs1(i,2));
                ss=sprintf('%s %s \n',ss,out1);
            end            
            
            x1=0;
            
            for i=1:Nrows
            
                ss=sprintf('%s \n',ss);

                ca=data_s{i,1};
                cb=data_s{i,2};
                cc=data_s{i,3};

                if(contains(ca,'Joint'))
            
                    v=cc;
            
                    out1=sprintf('\n Step %d   Joint   %g plateau remaining',i,v);

                    ss=sprintf('%s %s \n',ss,out1);
            
                    [srs2]=SRS_plateau_attenuation(srs1,v);
            
                else

                    v=cb;
            
                    if(contains(ca,'Cylindrical Shell'))
                        sq='Cylindrical Shell';
                        n=1;
                    end
                    if(contains(ca,'Longeron or Stringer'))
                        sq='Longeron or Stringer';
                        n=2;
                    end
                    if(contains(ca,'Skin/Ring Frame'))
                        sq='Skin/Ring Frame';
                        n=3;
                    end
                    if(contains(ca,'Primary Truss'))
                        sq='Primary Truss';
                        n=4;
                    end
                    if(contains(ca,'Complex Airframe'))
                        sq='Complex Airframe';
                        n=5;
                    end
                    if(contains(ca,'Complex Equipment Structure'))
                        sq='Complex Equipment Structure';
                        n=6;
                    end
                    if(contains(ca,'Honeycomb'))
                        sq='Honeycomb';
                        n=7;
                    end
            
                    if(app.iu==1)
                        out1=sprintf('\n Step %d  %s   Delta Distance=%g in',i,sq,v);
                    else
                        out1=sprintf('\n Step %d  %s   Delta Distance=%g m',i,sq,v);
                    end
                    ss=sprintf('%s %s \n',ss,out1);
            
                    x2=v+x1;
            
                    xx1=x1;
                    xx2=x2;
            
                    if(app.iu==2)
                        xx1=x1*100/2.54;
                        xx2=x2*100/2.54;
                    end
            
                    [attr1,attp1]=MM_ramp_plateau(xx1,n);
                    [attr2,attp2]=MM_ramp_plateau(xx2,n);
            
                    if(attr2==0 || attr1==0)
                        attr=0;
                    else
                        attr=attr2/attr1;
                    end
            
                    if(attp2==0 || attp1==0)
                        attp=0;
                    else
                        attp=attp2/attp1;
                    end
            
                    if(nrp==2)
                        attr=attp;
                    end
            
                    if(attr<attp)
                        attr=attp;
                    end
            
                    out1=sprintf('\n  Remaining:  ramp=%6.3g   plateau=%6.3g ',attr,attp);
                    ss=sprintf('%s %s \n',ss,out1);
            
                    f=srs1(:,1);
                    a=srs1(:,2);
            
                    [f,a]=srs_distance_atten_ramp_plateau(attp,attr,f,a);
                    srs2=[f a];
            
                    x1=x2;
            
                end
            
                srs1=srs2;

                ss=sprintf('%s \n\n fn(Hz)  Accel(G)  \n',ss);
            
                f=srs1(:,1);
                for ik=1:length(f)
                    out1=sprintf(' %6.0f  %8.4g ',srs1(ik,1),srs1(ik,2));
                    ss=sprintf('%s %s \n',ss,out1);
                end
            
            end

            app.SRSResultsTextArea.Value=ss;
   
            %%%%%%%%%%%%%%%%%%%
            
            ppp1=THM;
            ppp2=srs1;

            app.atten_srs=srs1;
            
            leg1='Source';
            leg2='Attenuated';
            
            fmin=f(1);
            fmax=f(end);
            
            %%%
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            
            
            if(CV==1)
            
                [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
            else
                ppp50=[fmin 0.8*fmin; fmax 0.8*fmax ];
                leg50='50 ips';
            
                [fig_num,h2]=plot_loglog_function_md_three_h2_third_dash(fig_num,x_label,...
                              y_label,t_string,ppp1,ppp2,ppp50,leg1,leg2,leg50,fmin,fmax,md);
            end

            app.UITable.ColumnFormat = {{'Cylindrical Shell' 'Longeron or Stringer' 'Skin/Ring Frame' 'Primary Truss' 'Complex Airframe' 'Complex Equipment Structure' 'Honeycomb' 'Joint'} 'char' 'char'};
            app.UITable_2.ColumnFormat = {{'Distance' 'Joint'} 'char' 'char'};          

        end

        % Button pushed function: CalculateButton_7005
        function CalculateButton_7005Pushed(app, event)
            CloseFigures(app);
 
            Items = strtrim(app.Add50ipsLineListBox.Items);
            im = strtrim(app.Add50ipsLineListBox.Value);
            CV = find(strcmp(Items, im));

            app.nsteps = str2num(app.NumberofAttenuationStepsListBox_7005.Value);
     
            A=app.UITable_2.Data;

            if(~isempty(A))
            
                sz=size(A);
                Arows=sz(1);
            
                for i=1:sz(1)
                    for j=1:sz(2)
                        data_s{i,j}=A{i,j};
                    end
                end
            
            end

            % Ncolumns=3;
            Nrows=app.nsteps;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            md=5;
            
            t_string=app.SRSPlotTitleEditField.Value;
            
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            
            srs1=THM;
            f=THM(:,1);
       
            %%%%%%%%%%%%%%%%%%%

            ss=sprintf(' Source SRS \n\n   fn(Hz)  Accel(G) \n');
            
            for i=1:length(f)
                out1=sprintf(' %6.0f  %8.4g ',srs1(i,1),srs1(i,2));
                ss=sprintf('%s %s \n',ss,out1);
            end        

            x1=0;
            
            for i=1:Nrows
                 
                if(contains(data_s{i,1},'Distance'))  % distance
             
                    if(app.iu==1)
                        out1=sprintf('\n Step %d   Distance Attenuation   Delta Distance=%g in',i,data_s{i,2});
                    else
                        out1=sprintf('\n Step %d   Distance Attenuation   Delta Distance=%g m',i,data_s{i,2});          
                    end

                    ss=sprintf('%s %s \n',ss,out1);
                    
                    x2=data_s{i,2}+x1;
                    
                    if(app.iu==1)
                        xx1=x1*2.54/100;
                        xx2=x2*2.54/100;            
                    else        
                        xx1=x1/100;
                        xx2=x2/100;
                    end
            
                    
                    [attr1,attp1]=NASA7005_ramp_plateau(xx1);
                    [attr2,attp2]=NASA7005_ramp_plateau(xx2);
            
                    if(attr2==0 || attr1==0)
                        attr=0;
                    else    
                        attr=attr2/attr1;
                    end
                    
                    if(attp2==0 || attp1==0)
                        attp=0;
                    else    
                        attp=attp2/attp1;
                    end
                    
                    out1=sprintf('\n  Remaining:  ramp=%6.3g   plateau=%6.3g ',attr,attp);
                    ss=sprintf('%s %s \n',ss,out1);
                    
                    f=srs1(:,1);
                    a=srs1(:,2);
                    
                    [f,a]=srs_distance_atten_ramp_plateau(attp,attr,f,a);
                    srs2=[f a];
                    
                    x1=x2;
                
                else         % joint
                    
                    out1=sprintf('\n Step %d   Joint   %g remaining',i,data_s{i,3});
                    ss=sprintf('%s %s \n',ss,out1);
                    
                    [srs2]=SRS_plateau_attenuation(srs1,data_s{i,3});
                    
                end
                
                srs1=srs2;
                
                ss=sprintf('%s \n fn(Hz)  Accel(G)  \n',ss);
            
                f=srs1(:,1);
                for ik=1:length(f)
                    out1=sprintf(' %6.0f  %8.4g ',srs1(ik,1),srs1(ik,2));
                    ss=sprintf('%s %s \n',ss,out1);
                end
                
            end

            app.SRSResultsTextArea.Value=ss;

            %%%%%%%%%%%%%%%%%%%
            
            ppp1=THM;
            ppp2=srs1;
            
            app.atten_srs=srs1;

            leg1='Source';
            leg2='Attenuated';
            
            fmin=f(1);
            fmax=f(end);
            
            %%%
            
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);

            app.UITable.ColumnFormat = {{'Cylindrical Shell' 'Longeron or Stringer' 'Skin/Ring Frame' 'Primary Truss' 'Complex Airframe' 'Complex Equipment Structure' 'Honeycomb' 'Joint'} 'char' 'char'};
            app.UITable_2.ColumnFormat = {{'Distance' 'Joint'} 'char' 'char'};           
                       
        end

        % Value changed function: NumberofAttenuationStepsListBox
        function NumberofAttenuationStepsListBoxValueChanged(app, event)
  
            MM_Change_Rows(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            units(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NumberofAttenuationStepsListBox.Value='1';
            MM_Change_Rows(app);
            data_s = cell(1, 3);
            app.UITable.Data=data_s;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            app.nsteps = str2num(app.NumberofAttenuationStepsListBox.Value);

            Nrows=app.nsteps;
            
            Ncolumns=3;

            A=app.UITable.Data;
            
            k=1;
            for i=1:Nrows
                channela{i}=A{k}; k=k+1;
            end
            for i=1:Nrows
                channelb{i}=A{k}; k=k+1;
            end
            
            for i=1:Nrows
                channelc{i}=A{k}; k=k+1;
            end
            
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}=A{i,j};
                end
            end
            
            for i=1:Nrows
                ca=char(channela{i});
                cb=char(channelb{i});
                cc=char(channelc{i});
                if(contains(ca,'Joint'))
                        data_s{i,2}='n/a';
                        if(contains(cc,'n/a'))
                            data_s{i,3}='';
                        end
                else
                        data_s{i,3}='n/a';
                        if(contains(cb,'n/a'))
                            data_s{i,2}='';
                        end
                end
            end
            
            app.UITable.Data=data_s;
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
          
            try
                MMshock.iu=app.UnitsListBox.Value;
            catch
            end

            try
                MMshock.ramp=app.SetRampAttenuationtoPlateauAttenuationListBox.Value;
            catch
            end

            try
                MMshock.add50=app.Add50ipsLineListBox.Value;
            catch
            end

            try
                MMshock.data=app.UITable.Data;
            catch
            end

            try
                MMshock.data_2=app.UITable_2.Data;
            catch
            end

            try
                MMshock.steps=app.NumberofAttenuationStepsListBox.Value;
            catch
            end

            try
                MMshock.title=app.SRSPlotTitleEditField.Value;
            catch
            end

            try
                MMshock.input_array=app.SelectInputArrayListBox.Value;
            catch
            end

            try
                FS=app.SelectInputArrayListBox.Value;
                THM=evalin('base',FS);
                ss=sprintf('save %s.mat THM',FS);
                eval(ss);
            catch
            end
            
            % % %
            
            structnames = fieldnames(MMshock, '-full'); % fields in the struct
            
            % % %
            
               [writefname, writepname] = uiputfile('*.mat','Save data as');
            
               %%%    writepfname = fullfile(writepname, writefname);
            
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
            
                elk=sprintf('%s%s',writepname,writefname);
            
                try
            
                    save(elk, 'MMshock');
            
                catch
                    warndlg('Save error');
                    return;
                end
            
            %%% SSS=load(elk)
            
            %%%%%%%%%%
            %%%%%%%%%%
            
            msgbox('Save Complete');
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
   
            [filename, pathname] = uigetfile('*.mat', 'Select save file');
            
            NAME = [pathname,filename];
            
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
            
            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
            
            MMshock=evalin('base','MMshock');
            
            catch
               warndlg(' evalin failed ');
               return;
            end
            
            %%%%%%%%%%%%
            
            try
                app.UnitsListBox.Value=MMshock.iu;
            catch
            end

            try
                app.SetRampAttenuationtoPlateauAttenuationListBox.Value=MMshock.ramp;
            catch
            end

            try
                app.Add50ipsLineListBox.Value=MMshock.add50;
            catch
            end

            try
                app.UITable.Data=MMshock.data;
            catch
            end

            try
                app.UITable.Data_2=MMshock.data_2;                
            catch
            end

            try
                app.NumberofAttenuationStepsListBox.Value=MMshock.steps;
            catch
            end

            try
                app.SRSPlotTitleEditField.Value=MMshock.title;
            catch
            end

            try
                app.SelectInputArrayListBox.Value=MMshock.input_array;
            catch
            end

            try
                FS=app.SelectInputArrayListBox.Value;
                ss=sprintf('load %s.mat',FS);
                eval(ss);
            catch
            end
                     
            CloseFigures(app);
            update_srs(app);
            units(app);

            msgbox('Load complete')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            try
                data=app.atten_srs;
                
                output_name=app.AttenautedSRSNameEditField.Value;
    
                assignin('base', output_name, data);
                
                msgbox('Save Complete');
            catch
                warndlg('Save Failed');
            end

        end

        % Value changed function: NumberofAttenuationStepsListBox_7005
        function NumberofAttenuationStepsListBox_7005ValueChanged(app, event)
            N7005_Change_Rows(app);
        end

        % Callback function
        function UITable_7005CellEdit(app, event)
            app.nsteps = str2num(app.NumberofAttenuationStepsListBox_2.Value);

            Nrows=app.nsteps;
            
            Ncolumns=3;

            
            % disp('A')
            A=app.UITable_2.Data

            return
            
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}=A{i,j};
                end
            end

            disp('data_s')
            data_s

            for i=1:Nrows
                i
                ca=string(A{i,1})
                cb=string(A{i,2})
                cc=string(A{i,3})

                if(contains(ca,'Joint'))
                        data_s{i,2}='n/a';
                        if(contains(cc,'n/a'))
                            data_s{i,3}='';
                        end
                else
                        data_s{i,3}='n/a';
                        if(contains(cb,'n/a'))
                            data_s{i,2}='';
                        end
                end
            end
            
            app.UITable_2.Data=data_s;

        end

        % Button pushed function: ResetButton_7005
        function ResetButton_7005Pushed(app, event)
            app.NumberofAttenuationStepsListBox_2.Value='1';
            N7005_Change_Rows(app);
            data_s = cell(1, 3);
            app.UITable_2.Data=data_s;
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
           app.nsteps = str2num(app.NumberofAttenuationStepsListBox_7005.Value);

            Nrows=app.nsteps;
            
            Ncolumns=3;

            % disp('A')
            A=app.UITable_2.Data;
            
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}=A{i,j};
                end
            end

            % disp('data_s')
            % data_s

            for i=1:Nrows
                ca=string(A{i,1});
                cb=string(A{i,2});
                cc=string(A{i,3});

                if(contains(ca,'Joint'))
                        data_s{i,2}='n/a';
                        if(contains(cc,'n/a'))
                            data_s{i,3}='';
                        end
                else
                        data_s{i,3}='n/a';
                        if(contains(cb,'n/a'))
                            data_s{i,2}='';
                        end
                end
            end
            
            app.UITable_2.Data=data_s;
            
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            update_wavelet(app);
        end

        % Button pushed function: CalculateButton_joint_wavelet
        function CalculateButton_joint_waveletPushed(app, event)
        
            fig_num=10;
        
        % amp   freq   nhs  delay
        
            try
                FS=app.SelectInputArrayListBox_wavelet.Value;
                wt=evalin('base',FS);
            catch
                warndlg('Input array not found');
                return; 
            end

              f=wt(:,2);
            amp=wt(:,3);
            nhs=wt(:,4);
             td=wt(:,5);
        
            sr=50*max(f);
            dt=1/sr;
        
            dur=0.5;
            nt=round(dur/dt);
        
        %%
        
            tt=zeros(nt,1);
            app.Ain=zeros(nt,1);
            app.Atr=zeros(nt,1);
            
            clear length;
            nwaves=length(f);
            
            tend=zeros(nwaves,1);
            
            ratio=zeros(nwaves,1);
            
            new_amp=zeros(nwaves,1);
          
            beta=zeros(nt,1);
            alpha=zeros(nt,1);
          
            app.new_wavelet_table=zeros(nwaves,5); 
        
        %%
        
            disp(' Scaling wavelets...');
            
            fc=str2num(app.MaximumUnityGainFrequencyHzEditField.Value);
            slope=str2num(app.DownwardSlopedBoctEditField.Value);
            
            for i=1:nwaves
               
                [rr]=bending_joint_atten_us(f(i),fc,slope);
        
                beta(i)=2*pi*f(i);
                alpha(i)=beta(i)/nhs(i);
               
                
                NF=nhs(i)/(2*f(i));
                
                tend(i)=td(i)+NF;
          
                ratio(i)=rr;
                
                new_amp(i)=amp(i)*ratio(i);
                
                app.new_wavelet_table(i,:)=[ i f(i) new_amp(i) nhs(i)  td(i) ];           
                     
            end
                    
        %%
        
            f=fix_size(f);
            ratio=fix_size(ratio);
            
            fff=[f ratio];
            fff=sortrows(fff,1);
             
            
        %%
            clear ff;
            clear rr;
        
            oct=2^(1/6);
            ff(1)=10;
            k=1;
            while(1)
          
                [rq]=bending_joint_atten_us(ff(k),fc,slope);
                
                rr(k)=rq;
                
                if(ff(k)>12000)
                    break;
                end
                
                k=k+1;
                ff(k)=ff(k-1)*oct;
            end    
        %%
        
            figure(fig_num);
            fig_num=fig_num+1;
          
            
            plot(ff,rr);
            grid on;
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log');
            xlabel('Frequency (Hz)');
            ylabel('Ratio');
            xlim([10 10000]);
            ylim([0.1 2]);
            title('Remaining Ratio ');

        %%    
        
            for i=1:nt
            
                tt(i)=(i-1)*dt; 
                t=tt(i);    
        %
        %  Incident
        %
                for j=1:nwaves 
            
                    if(t>=td(j) && t<=tend(j))
                       tq=t-td(j); 
                       app.Ain(i)=app.Ain(i)+amp(j)*sin(alpha(j)*tq)*sin(beta(j)*tq);
                    end 
                    
                end    
        % 
        %  Transmitted
        %
                for j=1:nwaves 
                
                    if(t>=td(j) && t<= tend(j))
                        tq=t-td(j);
                        app.Atr(i)=app.Atr(i)+new_amp(j)*sin(alpha(j)*tq)*sin(beta(j)*tq);
                    end
            
                end
        %
        
            end
            
            disp(' ');
            disp(' Calculating shock response spectra... ');
            
            clear f;
        
            f(1)=10;
        
            damp=0.05;
        
            k=1;
            
            oct=2^(1/12);
            
            while(1)
           
                fnn=f(k)*oct;
            
                if(fnn>23000)
                    break;
                else
                    k=k+1;
                    f(k)=fnn;
                end
            
            end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
            clear srs;
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,app.Atr);
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
                
            title('Transmitted');     
            
        %%
            tt=fix_size(tt);
            app.Ain=fix_size(app.Ain);
            app.Atr=fix_size(app.Atr);
            
            figure(fig_num);
            fig_num=fig_num+1;
                subplot(2,1,1);
                plot(tt,app.Ain);
                grid on;
                xlabel('Time (sec)');
                ylabel('Accel (G)');
                out1=sprintf('Incident');
                title(out1);
                yLimits = get(gca,'YLim');
                yq=max(abs(yLimits));
                ylim([-yq yq])
        %
                subplot(2,1,2);
                plot(tt,app.Atr);
                grid on;
                xlabel('Time (sec)');
                ylabel('Accel (G)');
                ylim([-yq yq])
                
                title('Transmitted'); 
         
        %%%
            
            [~,app.srs_in]=srs_function_abs(app.Ain,dt,damp,f);
            [~,app.srs_tr]=srs_function_abs(app.Atr,dt,damp,f);    
     
        %%%    

            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.srs_in(:,1),app.srs_in(:,2),app.srs_tr(:,1),app.srs_tr(:,2));
            legend ('Incident','Transmitted','location','northwest');       
            ylabel('Peak Accel (G)');
        %
            Q=1/(2*damp);
            if(damp==0.05)
                Q=10;
            end

            md=5;
            
            xlabel('Natural Frequency (Hz)');
            out5 = sprintf(' Shock Response Spectrum Q=%g ',Q);
            title(out5);
            grid;
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log');
            xlim([10 10000]);

            AAA=app.srs_in(:,2);
            min_amp=min(min(AAA));
            max_amp=max(max(AAA));

            [ymax,ymin]=ymax_ymin_md(max_amp,min_amp,md);

            ylim([ymin, ymax]);
            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    

            scale=max(abs(app.Atr))/max(abs(app.Ain));
            out1=sprintf(' Overall Remaining Ratio \n Time Domain Peaks \n    = %8.4g',scale); 
            
            app.ResultTextArea.Value=out1;

            app.TabGroup4.SelectedTab =app.TabGroup4.Children(3);
         
        end

        % Callback function
        function SaveButton_2Pushed(app, event)


        end

        % Value changing function: MaximumUnityGainFrequencyHzEditField
        function MaximumUnityGainFrequencyHzEditFieldValueChanging(app, event)
            app.ResultTextArea.Value='';
        end

        % Value changing function: DownwardSlopedBoctEditField
        function DownwardSlopedBoctEditFieldValueChanging(app, event)
            app.ResultTextArea.Value='';
        end

        % Value changed function: SelectInputArrayListBox_wavelet
        function SelectInputArrayListBox_waveletValueChanged(app, event)
            app.ResultTextArea.Value='';
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed2(app, event)
            Items = strtrim(app.FunctionListBox.Items);
            im = strtrim(app.FunctionListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
               data=app.Ain;
            end
            if(n==2)
               data=app.srs_in';
            end
            if(n==3)
               data=app.Atr; 
            end
            if(n==4)
               data=app.srs_tr; 
            end
            if(n==5)
               data=app.new_wavelet_table;  
            end
            
            output_name=app.OutputArrayNameEditField.Value;
            assignin('base', output_name, data);
            
            msgbox('Save Complete'); 

        end

        % Value changed function: UnitsListBox_2
        function UnitsListBox_2ValueChanged(app, event)
            beam_units(app);
            
        end

        % Button pushed function: CalculateButton_beam
        function CalculateButton_beamPushed(app, event)
            
            x=str2num(app.DistancefromSourceinEditField.Value);
           
            
            disp(' ');
            disp(' * * * * ');
            disp(' ');
            disp(' Distance from Source ');
            
            if(app.beam_iu==1)
                out1=sprintf(' x=%8.4g in ',x);
            else    
                out1=sprintf(' x=%8.4g cm ',x);    
                x=x/2.54;
            end
            ss=out1;


                damp=str2num(app.DampingPercentEditField.Value);

                thick=str2num(app.ThicknessinEditField.Value);
                width=str2num(app.WidthinEditField.Value);    
                
             L=str2num(app.DistancefromSourceinEditField.Value);   
                
                if(app.beam_iu==1)
                   app.rho=app.rho/386; 
                else
                   [app.E]=GPa_to_Pa(app.E);
                   width=width/1000;
                   thick=thick/1000;
                   L=L/100;
                end
                
                [area,MOI,~]=beam_rectangular_geometry(width,thick); 
                
                mp=app.rho*area;
                
                lf=2*damp/100;
                
                B=app.E*MOI;
                x=L;
            
            %%
            
            % amp   freq   nhs  delay
            
                try
                    FS=app.SelectInputArrayListBox_wavelet.Value;
                    wt=evalin('base',FS);
                catch
                    warndlg('Input array not found');
                    return; 
                end

                  f=wt(:,2);
                amp=wt(:,3);
                nhs=wt(:,4);
                 td=wt(:,5);
            
                sr=50*max(f);
                dt=1/sr;
            
                dur=0.5;
                nt=round(dur/dt);
           
                tt=zeros(nt,1);
                app.Ain=zeros(nt,1);
                app.Atr=zeros(nt,1);
                
                clear length;
                nwaves=length(f);
                 
                tstart=zeros(nwaves,1);
                tend=zeros(nwaves,1);
                
                tin_end=zeros(nwaves,1);
                
                tdx=zeros(nwaves,1);
                ratio=zeros(nwaves,1);
                
                new_amp=zeros(nwaves,1);
              
                beta=zeros(nt,1);
                alpha=zeros(nt,1);
              
                app.new_wavelet_table=zeros(nwaves,5); 
            
            %%
            
                disp(' Scaling wavelets...');
                
                for i=1:nwaves
                    
                    omega=2*pi*f(i);
            
                    beta(i)=2*pi*f(i);
                    alpha(i)=beta(i)/nhs(i);
                    
                    CB=( (B/mp)^(1/4))*sqrt(omega);
                
                    Cg=2*CB;
            
                    Dp=27.2*f(i)*lf/Cg;
                
                    delta=-Dp*x;
                    ratio(i)=10^(delta/20); 
                    
                    tdx(i)=x/Cg;
            
                    tstart(i)=(td(i)+tdx(i));
                    
                    NF=nhs(i)/(2*f(i));
                    
                    tend(i)=tstart(i)+NF;
                
                    tin_end(i)=td(i)+NF;
                    
                    new_amp(i)=amp(i)*ratio(i);
                    
                    app.new_wavelet_table(i,:)=[ i f(i) new_amp(i) nhs(i)  tstart(i) ];           
                         
                end
                
                f=fix_size(f);
                ratio=fix_size(ratio);
                
                fff=[f ratio];
                fff=sortrows(fff,1);
                
            %%
                clear CB;
                clear Cg;
            
                oct=2^(1/6);
                ff(1)=10;
                k=1;
                while(1)
                    
                    omega=2*pi*ff(k);
             
                    CB(k)=( (B/mp)^(1/4))*sqrt(omega);
                
                    Cg(k)=2*CB(k);
            
                    Dp=27.2*ff(k)*lf/Cg(k);
                
                    delta=-Dp*x;
                    rr(k)=10^(delta/20);
                    
                    if(ff(k)>12000)
                        break;
                    end
                    
                    k=k+1;
                    ff(k)=ff(k-1)*oct;
                end    
            %%
            
                fig_num=100;
                figure(fig_num);
                fig_num=fig_num+1;
                plot(ff,Cg,ff,CB);
                legend('Group','Phase','location','northwest');
                xlabel('Frequency (Hz)');
                if(app.beam_iu==1)
                        out1=sprintf('Bending Wave Speed  x=%6.3g in, damp=%6.3g%%',x,100*lf/2);
                        ylabel('Speed (in/sec)');
                else
                        out1=sprintf('Bending Wave Speed  x=%6.3g cm, damp=%6.3g%%',x,100*lf/2);  
                        ylabel('Speed (m/sec)');        
                end  
                title(out1);   
                set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','log'); 
                grid on;
                xlim([10 10000]);   
                
                figure(fig_num);
                fig_num=fig_num+1;
              
                plot(ff,rr);
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
                xlabel('Frequency (Hz)');
                ylabel('Ratio');
                xlim([10 10000]);
                if(app.beam_iu==1)
                        out1=sprintf('Remaining Ratio  x=%6.3g in, damp=%6.3g%%',x,100*lf/2);
                else
                        out1=sprintf('Remaining Ratio  x=%6.3g cm, damp=%6.3g%%',x,100*lf/2);            
                end  
                title(out1);
                
                
            %%    
            
                for i=1:nt
                
                    tt(i)=(i-1)*dt; 
                    t=tt(i);    
            %
            %  Source
            %
                    for j=1:nwaves 
            
                        
                        if(t>=td(j) && t<=tin_end(j))
                           tq=t-td(j); 
                           app.Ain(i)=app.Ain(i)+amp(j)*sin(alpha(j)*tq)*sin(beta(j)*tq);
                        end 
                        
                    end    
            % 
            %  Transmitted
            %
                    for j=1:nwaves 
                    
                        if(t>=tstart(j) && t<= tend(j))
                            tq=t-tstart(j);
                            app.Atr(i)=app.Atr(i)+new_amp(j)*sin(alpha(j)*tq)*sin(beta(j)*tq);
                        end
                
                    end
            %
            
                end
                
                disp(' ');
                disp(' Calculating shock response spectra... ');
                
                clear f;
            
                f(1)=10;
            
                damp=0.05;
            
                k=1;
                
                oct=2^(1/12);
                
                while(1)
               
                    fnn=f(k)*oct;
                
                    if(fnn>23000)
                        break;
                    else
                        k=k+1;
                        f(k)=fnn;
                    end
                
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                clear srs;
                
                figure(fig_num);
                fig_num=fig_num+1;
                plot(tt,app.Atr);
                grid on;
                xlabel('Time (sec)');
                ylabel('Accel (G)');
                    
                if(app.beam_iu==1)
                        out1=sprintf('Transmitted  x=%6.3g in, damp=%6.3g%%',x,100*lf/2);
                else
                        out1=sprintf('Transmitted  x=%6.3g cm, damp=%6.3g%%',x,100*lf/2);            
                end    
                title(out1);     
                
            %%
                tt=fix_size(tt);
                app.Ain=fix_size(app.Ain);
                app.Atr=fix_size(app.Atr);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            xlabel2='Time (sec)';
            ylabel1='Accel (G)';
            ylabel2=ylabel1;
            data1=[tt app.Ain];
            data2=[tt app.Atr];
            
            t_string1='Source';
            
            if(app.beam_iu==1)
                t_string2=sprintf('Transmitted  x=%6.3g in, damp=%6.3g%%',x,100*lf/2);
            else
                t_string2=sprintf('Transmitted  x=%6.3g cm, damp=%6.3g%%',x,100*lf/2);            
            end
            
            [fig_num]=...
                subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                setappdata(0,'Ain',[tt app.Ain]);
                setappdata(0,'Atr',[tt app.Atr]);    
                
                [~,app.srs_in]=srs_function_abs(app.Ain,dt,damp,f);
                [~,app.srs_tr]=srs_function_abs(app.Atr,dt,damp,f);    
                
                setappdata(0,'srs_in',app.srs_in);
                setappdata(0,'srs_tr',app.srs_tr);    
                
            %%%    
                Q=1/(2*damp);
                if(damp==0.05)
                    Q=10;
                end
                
                t_string = sprintf(' Shock Response Spectrum Q=%g ',Q);
            
                md=5;
                x_label='Natural Frequency (Hz)';
                y_label='Peak Accel (G)';
                fmin=app.srs_in(1,1);
                fmax=app.srs_in(end,1);
                if(fmax>10000)
                    fmax=10000;
                end
                ppp1=app.srs_in;
                ppp2=app.srs_tr;
                leg1='Source';
                leg2='Transmitted';
                
                [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
   
                scale=max(abs(app.Atr))/max(abs(app.Ain));
                out1=sprintf('\n Overall Remaining Ratio \n Time Domain Peaks \n\n = %8.4g ',scale); 
 
                app.ResultTextArea.Value=sprintf('%s\n%s',ss,out1);

                app.TabGroup4.SelectedTab =app.TabGroup4.Children(3);

        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)

            beam_units(app);
        end

        % Value changed function: UnitsListBox_longitudinal_wave
        function UnitsListBox_longitudinal_waveValueChanged(app, event)
            
            UnitsLong(app);
        end

        % Value changed function: Material1ListBox
        function Material1ListBoxValueChanged(app, event)
            
            UnitsLong(app);
        end

        % Value changed function: Material2ListBox
        function Material2ListBoxValueChanged(app, event)
          
            UnitsLong(app);
        end

        % Button pushed function: CalculateButton_long
        function CalculateButton_longPushed(app, event)

            A1=str2num(app.CrossSectionAreain2EditField_m1.Value);
            rho1=str2num(app.MassDensitylbmin3EditField_m1.Value); 
            E1=str2num(app.ElasticModuluspsiEditField_m1.Value);
            
            A2=str2num(app.CrossSectionAreain2EditField_m2.Value);
            rho2=str2num(app.MassDensitylbmin3EditField_m2.Value); 
            E2=str2num(app.ElasticModuluspsiEditField_m2.Value);
            
            if(app.long_iu==1)
               rho1=rho1/386;
               rho2=rho2/386;   
            else
               A1=A1/1000^2; 
               A2=A2/1000^2;    
               [E1]=GPa_to_Pa(E1);
               [E2]=GPa_to_Pa(E2); 
            end
            
            c1=sqrt(E1/rho1);
            c2=sqrt(E2/rho2);
            
            Z1=A1*sqrt(E1*rho1);
            Z2=A2*sqrt(E2*rho2);
            
            stress_transmission_ratio=(Z2/Z1)*(2*Z1)^2/(Z1+Z2)^2;
            stress_reflection_ratio=(Z1-Z2)/(Z1+Z2);
            
            if(app.long_iu==1)
                c_unit='in/sec';
                z_unit='(lbf-sec)/in';    
            else
                c_unit='m/sec';
                z_unit='(N-sec)/m';    
            end
            
            ss=sprintf(' Longitudinal Wave Speed \n\n');
            
            out1=sprintf(' Rod 1:  %8.4g %s \n',c1,c_unit);
            out2=sprintf(' Rod 2:  %8.4g %s \n',c2,c_unit);

            ss=sprintf('%s %s %s\n Mechanical Impedance \n\n',ss,out1,out2);

            out1=sprintf(' Rod 1:  %8.4g %s \n',Z1,z_unit);
            out2=sprintf(' Rod 2:  %8.4g %s \n',Z2,z_unit);
  
            ss=sprintf('%s %s %s\n Stress & Velocity Ratios Relative to Incident \n\n',ss,out1,out2);
            
            out1=sprintf('   Transmission:  %8.4g \n',stress_transmission_ratio);
            out2=sprintf('     Reflection:  %8.4g \n',stress_reflection_ratio);

            ss=sprintf('%s %s %s\n Power & Energy Ratios Relative to Incident \n\n',ss,out1,out2);

            power_reflection_ratio=(stress_reflection_ratio)^2;
            power_transmission_ratio=1-power_reflection_ratio;
            
            out1=sprintf('   Transmission:  %8.4g \n',power_transmission_ratio);
            out2=sprintf('     Reflection:  %8.4g ',power_reflection_ratio);

            ss=sprintf('%s %s %s',ss,out1,out2);
            
            app.ResultsTextArea_long.Value=ss;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.shockpropagationUIFigure = uifigure('Visible', 'off');
            app.shockpropagationUIFigure.Name = 'shock propagation';
            app.ReturnButton = uibutton(app.shockpropagationUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.shock_propagation_aver11byTomIrvineLabel = uilabel(app.shockpropagationUIFigure);
            app.shock_propagation_aver11byTomIrvineLabel.Text = 'shock_propagation_a    ver 1.1   by Tom Irvine';
            app.TabGroup = uitabgroup(app.shockpropagationUIFigure);
            app.DistanceJointAttenuationTab = uitab(app.TabGroup);
            app.DistanceJointAttenuationTab.Title = 'Distance & Joint Attenuation';
            app.TabGroup2 = uitabgroup(app.DistanceJointAttenuationTab);
            app.SourceSRSTab = uitab(app.TabGroup2);
            app.SourceSRSTab.Title = 'Source SRS';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel = uilabel(app.SourceSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel.Text = 'The input SRS must have two columns: Natural Frequency (Hz) & SRS';
            app.listbox_method = uilistbox(app.SourceSRSTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.SourceSRSTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.SourceSRSTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.SourceSRSTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.SourceSRSTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SRSPlotTitleEditFieldLabel = uilabel(app.SourceSRSTab);
            app.SRSPlotTitleEditFieldLabel.Text = 'SRS Plot Title ';
            app.SRSPlotTitleEditField = uieditfield(app.SourceSRSTab, 'text');
            app.SRSPlotTitleEditField.Value = 'SRS Q=10';
            app.Add50ipsLineListBoxLabel = uilabel(app.SourceSRSTab);
            app.Add50ipsLineListBoxLabel.Text = 'Add 50 ips Line';
            app.Add50ipsLineListBox = uilistbox(app.SourceSRSTab);
            app.Add50ipsLineListBox.Items = {'No', 'Yes'};
            app.Add50ipsLineListBox.Value = 'No';
            app.UnitsListBoxLabel = uilabel(app.SourceSRSTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SourceSRSTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MartinMariettaTab = uitab(app.TabGroup2);
            app.MartinMariettaTab.Title = 'Martin-Marietta';
            app.CalculateButton = uibutton(app.MartinMariettaTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.MartinMariettaTab);
            app.Label.Text = 'Joint remaining ratio is typically about 0.7.      Recommended number of joints is 3 maximum.';
            app.NumberofAttenuationStepsListBoxLabel = uilabel(app.MartinMariettaTab);
            app.NumberofAttenuationStepsListBoxLabel.Text = {'Number of '; 'Attenuation Steps'};
            app.NumberofAttenuationStepsListBox = uilistbox(app.MartinMariettaTab);
            app.NumberofAttenuationStepsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofAttenuationStepsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofAttenuationStepsListBoxValueChanged, true);
            app.NumberofAttenuationStepsListBox.Value = '1';
            app.ResetButton = uibutton(app.MartinMariettaTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.MartinMariettaTab);
            app.UITable.ColumnName = {'Type'; 'Delta Distance (in)'; 'Plateau Remaining Ratio'};
            app.UITable.ColumnEditable = [true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tag = 'uitable_data';
            app.SetRampAttenuationtoPlateauAttenuationListBoxLabel = uilabel(app.MartinMariettaTab);
            app.SetRampAttenuationtoPlateauAttenuationListBoxLabel.Text = {'Set Ramp Attenuation '; 'to Plateau Attenuation'};
            app.SetRampAttenuationtoPlateauAttenuationListBox = uilistbox(app.MartinMariettaTab);
            app.SetRampAttenuationtoPlateauAttenuationListBox.Items = {'No', 'Yes'};
            app.SetRampAttenuationtoPlateauAttenuationListBox.Value = 'No';
            app.DoubleclickonTypecolumncellstoaccesspulldownmenuLabel = uilabel(app.MartinMariettaTab);
            app.DoubleclickonTypecolumncellstoaccesspulldownmenuLabel.Text = 'Double click on Type column cells to access pull down menu.';
            app.NASAHDBK7005Tab = uitab(app.TabGroup2);
            app.NASAHDBK7005Tab.Title = 'NASA-HDBK-7005';
            app.CalculateButton_7005 = uibutton(app.NASAHDBK7005Tab, 'push');
            app.CalculateButton_7005.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_7005Pushed, true);
            app.CalculateButton_7005.Text = 'Calculate';
            app.ResetButton_7005 = uibutton(app.NASAHDBK7005Tab, 'push');
            app.ResetButton_7005.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_7005Pushed, true);
            app.ResetButton_7005.Text = 'Reset';
            app.NumberofAttenuationStepsListBox_2Label = uilabel(app.NASAHDBK7005Tab);
            app.NumberofAttenuationStepsListBox_2Label.Text = {'Number of '; 'Attenuation Steps'};
            app.NumberofAttenuationStepsListBox_7005 = uilistbox(app.NASAHDBK7005Tab);
            app.NumberofAttenuationStepsListBox_7005.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofAttenuationStepsListBox_7005.ValueChangedFcn = createCallbackFcn(app, @NumberofAttenuationStepsListBox_7005ValueChanged, true);
            app.NumberofAttenuationStepsListBox_7005.Value = '1';
            app.UITable_2 = uitable(app.NASAHDBK7005Tab);
            app.UITable_2.ColumnName = {'Type'; 'Delta Distance (in)'; 'Plateau Remaining Ratio'};
            app.UITable_2.ColumnEditable = [true true true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.UITable_2.Tag = 'uitable_data';
            app.Label_2 = uilabel(app.NASAHDBK7005Tab);
            app.Label_2.Text = 'Joint remaining ratio is typically about 0.7.      Recommended number of joints is 3 maximum.';
            app.DoubleclickonTypecolumncellstoaccesspulldownmenuLabel_2 = uilabel(app.NASAHDBK7005Tab);
            app.DoubleclickonTypecolumncellstoaccesspulldownmenuLabel_2.Text = 'Double click on Type column cells to access pull down menu.';
            app.ResultsExportTab = uitab(app.TabGroup2);
            app.ResultsExportTab.Title = 'Results & Export';
            app.SRSResultsTextAreaLabel = uilabel(app.ResultsExportTab);
            app.SRSResultsTextAreaLabel.Text = 'SRS Results';
            app.SRSResultsTextArea = uitextarea(app.ResultsExportTab);
            app.SaveButton = uibutton(app.ResultsExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AttenautedSRSNameEditFieldLabel = uilabel(app.ResultsExportTab);
            app.AttenautedSRSNameEditFieldLabel.Text = 'Attenauted SRS Name';
            app.AttenautedSRSNameEditField = uieditfield(app.ResultsExportTab, 'text');
            app.SaveModelButton = uibutton(app.DistanceJointAttenuationTab, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.DistanceJointAttenuationTab, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.DistanceJointWaveletMethodTab = uitab(app.TabGroup);
            app.DistanceJointWaveletMethodTab.Title = 'Distance & Joint Wavelet Method';
            app.TabGroup4 = uitabgroup(app.DistanceJointWaveletMethodTab);
            app.SemiinfiniteBeamAnalogyTab_2 = uitab(app.TabGroup4);
            app.SemiinfiniteBeamAnalogyTab_2.Title = 'Semi-infinite Beam Analogy';
            app.CalculateButton_beam = uibutton(app.SemiinfiniteBeamAnalogyTab_2, 'push');
            app.CalculateButton_beam.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_beamPushed, true);
            app.CalculateButton_beam.Text = 'Calculate';
            app.UnitsListBox_2Label = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.UnitsListBox_2Label.Text = 'Units';
            app.UnitsListBox_2 = uilistbox(app.SemiinfiniteBeamAnalogyTab_2);
            app.UnitsListBox_2.Items = {'English', 'metric'};
            app.UnitsListBox_2.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_2ValueChanged, true);
            app.UnitsListBox_2.Value = 'English';
            app.DampingPercentEditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.DampingPercentEditFieldLabel.Text = 'Damping Percent';
            app.DampingPercentEditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.DampingPercentEditField.Value = '3.25';
            app.DistancefromSourceinEditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.DistancefromSourceinEditFieldLabel.Text = 'Distance from Source (in)';
            app.DistancefromSourceinEditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.WidthinEditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.ThicknessinEditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.MaterialListBoxLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.SemiinfiniteBeamAnalogyTab_2);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.SemiinfiniteBeamAnalogyTab_2);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.SemiinfiniteBeamAnalogyTab_2, 'text');
            app.JointWaveletTable_2 = uitab(app.TabGroup4);
            app.JointWaveletTable_2.Title = 'Joint Wavelet Table';
            app.CalculateButton_joint_wavelet = uibutton(app.JointWaveletTable_2, 'push');
            app.CalculateButton_joint_wavelet.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_joint_waveletPushed, true);
            app.CalculateButton_joint_wavelet.Text = 'Calculate';
            app.MaximumUnityGainFrequencyHzLabel = uilabel(app.JointWaveletTable_2);
            app.MaximumUnityGainFrequencyHzLabel.Text = {'Maximum Unity Gain '; 'Frequency (Hz)'};
            app.MaximumUnityGainFrequencyHzEditField = uieditfield(app.JointWaveletTable_2, 'text');
            app.MaximumUnityGainFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @MaximumUnityGainFrequencyHzEditFieldValueChanging, true);
            app.MaximumUnityGainFrequencyHzEditField.Value = '2000';
            app.DownwardSlopedBoctLabel = uilabel(app.JointWaveletTable_2);
            app.DownwardSlopedBoctLabel.Text = {'Downward Slope '; '(dB/oct)'};
            app.DownwardSlopedBoctEditField = uieditfield(app.JointWaveletTable_2, 'text');
            app.DownwardSlopedBoctEditField.ValueChangingFcn = createCallbackFcn(app, @DownwardSlopedBoctEditFieldValueChanging, true);
            app.DownwardSlopedBoctEditField.Value = '3';
            app.ViewResultsExportTab = uitab(app.TabGroup4);
            app.ViewResultsExportTab.Title = 'View Results & Export';
            app.ResultTextAreaLabel = uilabel(app.ViewResultsExportTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.ViewResultsExportTab);
            app.ResultTextArea.Editable = 'off';
            app.SaveButton_2 = uibutton(app.ViewResultsExportTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed2, true);
            app.SaveButton_2.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ViewResultsExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ViewResultsExportTab, 'text');
            app.FunctionListBoxLabel = uilabel(app.ViewResultsExportTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ViewResultsExportTab);
            app.FunctionListBox.Items = {'Source Time History     ', 'Source SRS              ', 'Transmitted Time History', 'Transmitted SRS         ', 'Transmitted Wavelet Table', ''};
            app.FunctionListBox.Value = 'Source Time History     ';
            app.ThewavelettablemusthavefourcolumnsLabel = uilabel(app.DistanceJointWaveletMethodTab);
            app.ThewavelettablemusthavefourcolumnsLabel.Text = {'The wavelet table must have four columns:     [ Index   Freq (Hz)   Accel (G)   Number of Half Sines    Delay (sec)]'; ''};
            app.UpdateTableButton = uibutton(app.DistanceJointWaveletMethodTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.SelectInputArrayListBox_2Label = uilabel(app.DistanceJointWaveletMethodTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_wavelet = uilistbox(app.DistanceJointWaveletMethodTab);
            app.SelectInputArrayListBox_wavelet.Items = {'Item 1'};
            app.SelectInputArrayListBox_wavelet.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBox_waveletValueChanged, true);
            app.StructureBorneWavePropagationTab = uitab(app.TabGroup);
            app.StructureBorneWavePropagationTab.Title = 'Structure-Borne Wave Propagation';
            app.Longitudinalwaveimpedancechangever10byTomIrvineLabel = uilabel(app.StructureBorneWavePropagationTab);
            app.Longitudinalwaveimpedancechangever10byTomIrvineLabel.Text = 'Longitudinal wave impedance change   ver 1.0   by Tom Irvine';
            app.TabGroup5 = uitabgroup(app.StructureBorneWavePropagationTab);
            app.DiagramTab = uitab(app.TabGroup5);
            app.DiagramTab.Title = 'Diagram';
            app.Image = uiimage(app.DiagramTab);
            app.Image.ImageSource = 'longitudinal_impedance.jpg';
            app.Longitudinalwaveimpedancechangever10byTomIrvineLabel_2 = uilabel(app.DiagramTab);
            app.Longitudinalwaveimpedancechangever10byTomIrvineLabel_2.Text = {'Longitudinal wave in rod with change in cross-sectional area and/or material properties.  '; ''; 'Incident, reflected and transmitted stress waves shown.'};
            app.MateralPropertiesTab = uitab(app.TabGroup5);
            app.MateralPropertiesTab.Title = 'Materal Properties';
            app.UnitsListBox_3Label = uilabel(app.MateralPropertiesTab);
            app.UnitsListBox_3Label.Text = 'Units';
            app.UnitsListBox_longitudinal_wave = uilistbox(app.MateralPropertiesTab);
            app.UnitsListBox_longitudinal_wave.Items = {'English', 'metric'};
            app.UnitsListBox_longitudinal_wave.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_longitudinal_waveValueChanged, true);
            app.UnitsListBox_longitudinal_wave.Value = 'English';
            app.Material2Panel = uipanel(app.MateralPropertiesTab);
            app.Material2Panel.Title = 'Material 2';
            app.Material2ListBoxLabel = uilabel(app.Material2Panel);
            app.Material2ListBoxLabel.Text = 'Material 2';
            app.Material2ListBox = uilistbox(app.Material2Panel);
            app.Material2ListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.Material2ListBox.ValueChangedFcn = createCallbackFcn(app, @Material2ListBoxValueChanged, true);
            app.Material2ListBox.Value = 'Aluminum';
            app.MassDensitylbmin3EditField_m2Label = uilabel(app.Material2Panel);
            app.MassDensitylbmin3EditField_m2Label.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField_m2 = uieditfield(app.Material2Panel, 'text');
            app.ElasticModuluspsiEditField_m2Label = uilabel(app.Material2Panel);
            app.ElasticModuluspsiEditField_m2Label.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField_m2 = uieditfield(app.Material2Panel, 'text');
            app.CrossSectionAreain2EditFieldLabel_m2 = uilabel(app.Material2Panel);
            app.CrossSectionAreain2EditFieldLabel_m2.Text = 'Cross-Section Area (in^2)';
            app.CrossSectionAreain2EditField_m2 = uieditfield(app.Material2Panel, 'text');
            app.Material1Panel = uipanel(app.MateralPropertiesTab);
            app.Material1Panel.Title = 'Material 1';
            app.Material1ListBoxLabel = uilabel(app.Material1Panel);
            app.Material1ListBoxLabel.Text = 'Material 1';
            app.Material1ListBox = uilistbox(app.Material1Panel);
            app.Material1ListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.Material1ListBox.ValueChangedFcn = createCallbackFcn(app, @Material1ListBoxValueChanged, true);
            app.Material1ListBox.Value = 'Aluminum';
            app.MassDensitylbmin3EditField_m1Label = uilabel(app.Material1Panel);
            app.MassDensitylbmin3EditField_m1Label.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField_m1 = uieditfield(app.Material1Panel, 'text');
            app.ElasticModuluspsiEditField_m1Label = uilabel(app.Material1Panel);
            app.ElasticModuluspsiEditField_m1Label.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField_m1 = uieditfield(app.Material1Panel, 'text');
            app.CrossSectionAreain2EditFieldLabel_m1 = uilabel(app.Material1Panel);
            app.CrossSectionAreain2EditFieldLabel_m1.Text = 'Cross-Section Area (in^2)';
            app.CrossSectionAreain2EditField_m1 = uieditfield(app.Material1Panel, 'text');
            app.CalculationTab = uitab(app.TabGroup5);
            app.CalculationTab.Title = 'Calculation';
            app.CalculateButton_long = uibutton(app.CalculationTab, 'push');
            app.CalculateButton_long.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_longPushed, true);
            app.CalculateButton_long.Text = 'Calculate ';
            app.ResultsTextAreaLabel = uilabel(app.CalculationTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea_long = uitextarea(app.CalculationTab);
            app.ResultsTextArea_long.Editable = 'off';
            % Show the figure after all components are created
            app.shockpropagationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shock_propagation_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.shockpropagationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.shockpropagationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.shockpropagationUIFigure)
        end
    end
end