classdef sigma_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DefineNonFilterTimeZoneListBox  matlab.ui.control.ListBox
        DefineNonFilterTimeZoneLabel    matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        PlotInputArrayButton            matlab.ui.control.Button
        EndTimemsecEditField_nfz        matlab.ui.control.EditField
        EndTimemsecEditField_nfzLabel   matlab.ui.control.Label
        StartTimemsecEditField_nfz      matlab.ui.control.EditField
        StartTimemsecEditField_nfzLabel  matlab.ui.control.Label
        NonFilterZoneTimesLabel         matlab.ui.control.Label
        OverallTimesLabel               matlab.ui.control.Label
        EndTimemsecEditField            matlab.ui.control.EditField
        EndTimemsecEditFieldLabel       matlab.ui.control.Label
        StartTimemsecEditField          matlab.ui.control.EditField
        StartTimemsecEditFieldLabel     matlab.ui.control.Label
        NumberofStagesEditField         matlab.ui.control.EditField
        NumberofStagesEditFieldLabel    matlab.ui.control.Label
        WindowSizemsecEditField         matlab.ui.control.EditField
        WindowSizemsecLabel             matlab.ui.control.Label
        SigmaThresholdEditField         matlab.ui.control.EditField
        YaxisLabelEditField_2Label      matlab.ui.control.Label
        YaxisLabelEditField             matlab.ui.control.EditField
        YaxisLabelEditFieldLabel        matlab.ui.control.Label
        TimeUnitListBox                 matlab.ui.control.ListBox
        TimeUnitListBoxLabel            matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimeamplitudeLabel  matlab.ui.control.Label
        sigma_filterver10byTomIrvineLabel  matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        t %
        a %
        infz %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)            
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end     
            
        end
        
        function Preprocess(app)
            ClosePlots(app);

            FS = app.InputArrayNameEditField.Value;
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end

            [THM,~]=time_check_with_linear_interpolation(THM);          

            app.t=THM(:,1);
            app.a=THM(:,2);

            Items = char(app.TimeUnitListBox.Items);
            im=app.TimeUnitListBox.Value;
            [itu]=find_index(Items,im);

            if(itu==1)
                app.t=app.t/1000;
            end 
        end
        
        function defineNFZ(app)
            Items = char(app.DefineNonFilterTimeZoneListBox.Items);
            im=app.DefineNonFilterTimeZoneListBox.Value;
            [app.infz]=find_index(Items,im);
            
            if(app.infz==1)
                app.NonFilterZoneTimesLabel.Visible='on';
                app.EndTimemsecEditField_nfz.Visible='on';
                app.EndTimemsecEditField_nfzLabel.Visible='on';     
                app.StartTimemsecEditField_nfz.Visible='on';
                app.StartTimemsecEditField_nfzLabel.Visible='on';              
            else
                app.NonFilterZoneTimesLabel.Visible='off'; 
                app.EndTimemsecEditField_nfz.Visible='off';
                app.EndTimemsecEditField_nfzLabel.Visible='off';     
                app.StartTimemsecEditField_nfz.Visible='off';
                app.StartTimemsecEditField_nfzLabel.Visible='off';             
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            defineNFZ(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            Preprocess(app);

            fig_num=2;

            nstages=str2num(app.NumberofStagesEditField.Value);
            
            tstart=str2num(app.StartTimemsecEditField.Value);
            tend=str2num(app.EndTimemsecEditField.Value);

            [~,ii]=min(abs(app.t-tstart));
            [~,jj]=min(abs(app.t-tend));

            app.t=app.t(ii:jj);
            app.a=app.a(ii:jj);

            if(app.infz==1)
                tt1=str2num(app.StartTimemsecEditField_nfz.Value);
                tt2=str2num(app.EndTimemsecEditField_nfz.Value);

                [~,iit]=min(abs(app.t-tt1));
                [~,jjt]=min(abs(app.t-tt2));            
            end

            dt=mean(diff(app.t));

            ws=str2num(app.WindowSizemsecEditField.Value);

            sigma=str2num(app.SigmaThresholdEditField.Value);

            num=round(ws/dt);

            dur=app.t(end)-app.t(1);

            ns=ceil(dur/ws);

            i1=1;

            aa=app.a;

            L=length(aa);

            fprintf('\n ws=%d  ns=%d  nstages=%d \n\n',ns,ws,nstages);

            for i=1:ns

                try
    
                    i2=i1+num;

                    if(i2>L)
                        i2=L;
                    end    
    
                    vv=aa(i1:i2);
    
                    for k=1:nstages
    
                        [~, minI] = min(vv);
                        [~, maxI] = max(vv);
        
                        Imin=min([minI maxI]);
                        Imax=max([minI maxI]);
        
                        vv(Imax)=[];
                        vv(Imin)=[];
    
                    end
     
                    sstd=std(vv);
                    amean=mean(vv);
    
                    for j=i1:i2
                 
                        if(abs(aa(j)/sstd)>sigma)
                            if(app.infz==1)
                                if(j<iit || j>jjt)
                                    aa(j)=amean;
                                end    
                            else
                                aa(j)=amean;
                            end
                        end
               
                    end
    
                    i1=i2+1;

                catch
                end
            end


            xlabel2='Time (msec)';

            YS=app.YaxisLabelEditField.Value;

            ylabel1=YS;
            ylabel2=YS;

            data1=[app.t app.a];
            data2=[app.t aa];

            t_string1='Input';
            t_string2='Filtered';

            [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);

  
            output_name=app.OutputArrayNameEditField.Value;

             assignin('base',output_name, data2);

        end

        % Button pushed function: PlotInputArrayButton
        function PlotInputArrayButtonPushed(app, event)
            Preprocess(app);

            figure(1);
            plot(app.t,app.a);

            xlabel('Time (msec)');
            YS=app.YaxisLabelEditField.Value;
            ylabel(YS);
            title('Input');
            grid on;
           
        end

        % Value changed function: DefineNonFilterTimeZoneListBox
        function DefineNonFilterTimeZoneListBoxValueChanged(app, event)
           defineNFZ(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.InputArrayNameEditFieldLabel = uilabel(app.UIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIFigure, 'text');
            app.sigma_filterver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.sigma_filterver10byTomIrvineLabel.Text = 'sigma_filter     ver 1.0  by Tom Irvine        This scripts filters peaks from a time history based on a standard deviation not-to-exceed threshold.';
            app.TheinputarraymusthavetwocolumnstimeamplitudeLabel = uilabel(app.UIFigure);
            app.TheinputarraymusthavetwocolumnstimeamplitudeLabel.Text = 'The input array must have two columns:  time & amplitude';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TimeUnitListBoxLabel = uilabel(app.UIFigure);
            app.TimeUnitListBoxLabel.Text = 'Time Unit';
            app.TimeUnitListBox = uilistbox(app.UIFigure);
            app.TimeUnitListBox.Items = {'sec', 'msec'};
            app.TimeUnitListBox.Value = 'msec';
            app.YaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.YaxisLabelEditField_2Label = uilabel(app.UIFigure);
            app.YaxisLabelEditField_2Label.Text = 'Sigma Threshold';
            app.SigmaThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.SigmaThresholdEditField.Value = '5';
            app.WindowSizemsecLabel = uilabel(app.UIFigure);
            app.WindowSizemsecLabel.Text = {'Window Size '; '(msec)'};
            app.WindowSizemsecEditField = uieditfield(app.UIFigure, 'text');
            app.WindowSizemsecEditField.Value = '10';
            app.NumberofStagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStagesEditFieldLabel.Text = 'Number of Stages';
            app.NumberofStagesEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofStagesEditField.Value = '6';
            app.StartTimemsecEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimemsecEditFieldLabel.Text = 'Start Time (msec)';
            app.StartTimemsecEditField = uieditfield(app.UIFigure, 'text');
            app.EndTimemsecEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimemsecEditFieldLabel.Text = 'End Time (msec)';
            app.EndTimemsecEditField = uieditfield(app.UIFigure, 'text');
            app.OverallTimesLabel = uilabel(app.UIFigure);
            app.OverallTimesLabel.Text = 'Overall Times';
            app.NonFilterZoneTimesLabel = uilabel(app.UIFigure);
            app.NonFilterZoneTimesLabel.Text = 'Non-Filter Zone Times';
            app.StartTimemsecEditField_nfzLabel = uilabel(app.UIFigure);
            app.StartTimemsecEditField_nfzLabel.Text = 'Start Time (msec)';
            app.StartTimemsecEditField_nfz = uieditfield(app.UIFigure, 'text');
            app.EndTimemsecEditField_nfzLabel = uilabel(app.UIFigure);
            app.EndTimemsecEditField_nfzLabel.Text = 'End Time (msec)';
            app.EndTimemsecEditField_nfz = uieditfield(app.UIFigure, 'text');
            app.PlotInputArrayButton = uibutton(app.UIFigure, 'push');
            app.PlotInputArrayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputArrayButtonPushed, true);
            app.PlotInputArrayButton.Text = 'Plot Input Array';
            app.OutputArrayNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputArrayNameEditField.Value = 'filtered_data';
            app.DefineNonFilterTimeZoneLabel = uilabel(app.UIFigure);
            app.DefineNonFilterTimeZoneLabel.Text = 'Define Non-Filter Time Zone';
            app.DefineNonFilterTimeZoneListBox = uilistbox(app.UIFigure);
            app.DefineNonFilterTimeZoneListBox.Items = {'Yes', 'No'};
            app.DefineNonFilterTimeZoneListBox.ValueChangedFcn = createCallbackFcn(app, @DefineNonFilterTimeZoneListBoxValueChanged, true);
            app.DefineNonFilterTimeZoneListBox.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigma_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end