classdef signal_correlation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        signal_correlationver11byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AutocorrelationTab             matlab.ui.container.Tab
        CalculateButton                matlab.ui.control.Button
        YaxisLabelEditField            matlab.ui.control.EditField
        YaxisLabelEditFieldLabel       matlab.ui.control.Label
        InputArrayNameEditField        matlab.ui.control.EditField
        InputArrayNameEditFieldLabel   matlab.ui.control.Label
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        CrossCorrelationTab            matlab.ui.container.Tab
        YaxisLabelEditField_2          matlab.ui.control.EditField
        YaxisLabelEditField_2Label     matlab.ui.control.Label
        SecondArrayNameEditField       matlab.ui.control.EditField
        SecondArrayNameEditFieldLabel  matlab.ui.control.Label
        FirstArrayNameEditField        matlab.ui.control.EditField
        FirstArrayNameEditFieldLabel   matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        InputFormatListBox             matlab.ui.control.ListBox
        InputFormatListBoxLabel        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function LB_change(app)
        
            value = app.InputFormatListBox.Value;
            if(contains(value,'Common'))
                app.SecondArrayNameEditFieldLabel.Visible = 'off';
                app.SecondArrayNameEditField.Visible = 'off';
                app.FirstArrayNameEditFieldLabel.Text = 'Common Array Name';                
            else   
                app.SecondArrayNameEditFieldLabel.Visible = 'on';
                app.SecondArrayNameEditField.Visible = 'on';
                app.FirstArrayNameEditFieldLabel.Text = 'First Array Name';
            end
            
        end
        
        function close_figures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LB_change(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                
            close_figures(app);
        
            FS = app.InputArrayNameEditField.Value;
            
            YS=app.YaxisLabelEditField.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            t=THM(:,1);
            amp=THM(:,2);
            dt=mean(diff(t));
            num=length(t);

            [xc,xmax,tmax]=cross_correlation_function_alt(amp,amp,num,dt);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(1);
            plot(THM(:,1),THM(:,2));
            title('Time History');
            xlabel(' Time(sec) ')
            ylabel(YS)
            grid on;
            %
            fprintf('\n Maximum occurs at %6.4g sec  Amp = %8.4g \n',tmax,xmax);
            %
            figure(2);
            plot(xc(:,1),xc(:,2));
            xlabel(' Delay(sec) ');
            ylabel('Rxx')
            out1=sprintf(' Autocorrelation   Max at %6.4g sec  Amp=%8.4g',tmax,xmax);
            title(out1);
            grid on;
            %
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            close_figures(app);
        
            YS=app.YaxisLabelEditField.Value;
        
            FS1 = app.FirstArrayNameEditField.Value;
            FS2 = app.SecondArrayNameEditField.Value;

            n=2;
            m=1;
            
            if(n==2)
                try
                    THM_1=evalin('base',FS1);
                catch
                    warndlg('First input Array not found')
                    return;
                end
                try
                    THM_2=evalin('base',FS2);
                catch
                    warndlg('Second input Array not found')
                    return;
                end                
            end
            
            if(n==1)
                t1=THM_1(:,1);
                a=THM_1(:,2);
                b=THM_1(:,3);        
            else
                t1=THM_1(:,1);
                t2=THM_2(:,1);
                a=THM_1(:,2);
                b=THM_2(:,2);       
            end
       
            fig_num=1;

            if(n==1)
                sz=size(THM_1);
                num=sz(2)-1;
                data1=[ t1 THM_1(:,2) ];
                data2=[ t1 THM_1(:,3) ];    
                if(num>=3)
                   data3=[ t1 THM_1(:,4) ]; 
                end
                if(num>=4)
                   data4=[ t1 THM_1(:,5) ]; 
                end    
            else 
                num=2;
                data1=[ t1 a ];
                data2=[ t2 b ];
            end

            xlabelx='Time(sec)';

            if(num==2)
                ylabel1=YS;
                ylabel2=YS;
                t_string1='Channel 1';
                t_string2='Channel 2';
                [fig_num]=subplots_two_linlin_titles(fig_num,xlabelx,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            end
            if(num==3)
                ylabel1=YS;
                ylabel2=YS;
                ylabel3=YS;
                t_string1='Channel 1';
                t_string2='Channel 2';
                t_string3='Channel 3';    
                [fig_num]=subplots_three_linlin_titles(fig_num,xlabelx,...
                              ylabel1,ylabel2,ylabel3,data1,data2,data3,...
                                            t_string1,t_string2,t_string3);
            end
            if(num==4)
                ylabel1=YS;
                ylabel2=YS;
                ylabel3=YS;
                ylabel4=YS;
                t_string1='Channel 1';
                t_string2='Channel 2';
                t_string3='Channel 3';  
                t_string4='Channel 4';     
                [fig_num]=subplots_four_linlin_titles(fig_num,xlabelx,...
                                        ylabel1,ylabel2,ylabel3,ylabel4,...
                                        data1,data2,data3,data4,...
                                        t_string1,t_string2,t_string3,t_string4);
            end

            %%%

            if(m==1)
               dt=mean(diff(t1));
            else
               num1=length(t1);
               dt1=mean(diff(t1));
            %   
               num2=length(t2);
               dt2=mean(diff(t2));  
            %
            %  Truncate if necessary
            %
                if(num1<num2)
                    num2=num1;
                end
                if(num2<num1)
                    num1=num2;
                end
                num=num1;
            %
                pe=abs((dt1-dt2)/dt1);
            %
                dt=(dt1+dt2)/2;
            %
                if(pe>0.01)
                    fprintf('Warning: dt1=%8.4g  dt2=%8.4g \n',dt1,dt2);
                    warndlg('time steps differ');
                end 
            %
            end

            np=length(data1(:,1));

            k=1;

            for i=1:(num-1)
                for j=(i+1):num

                    if(num==2)
                        a=data1(:,2);
                        b=data2(:,2);
                    else
                        a=THM_1(:,i+1);
                        b=THM_1(:,j+1);
                    end
        
                    [xc,xmax(k),tmax(k)]=cross_correlation_function(a,b,np,dt);
        
                    [rho(k)]=Pearson_coefficient(a,b);       
        
                    figure(fig_num);
                    fig_num=fig_num+1;
                    plot(a,b,'bo','MarkerSize',3);
                    grid on;
                    xL=sprintf('Channel %d Amplitude',i);        
                    yL=sprintf('Channel %d Amplitude',j);
                    xlabel(xL);
                    ylabel(yL);
                    out2=sprintf('Scatter Plot, Pearson Coefficient= %6.3g',rho(k));
                    title(out2);

                    figure(fig_num);
                    fig_num=fig_num+1;
                    plot(xc(:,1),xc(:,2));
                    xlabel('Delay(sec)');
                    ylabel('Rxy');
                    out1=sprintf(' Cross-correlation Channels %d & %d \n Max Abs = %8.4g at %8.4g sec',i,j,xmax(k),tmax(k));
                    title(out1);     
                    grid on;

                    if(num==2)
                        disp(' ');
                        disp(' Covariance Matrix: ');
                        disp(' ');
                        cvm = cov(a,b)
                    end
                    k=k+1;
                end
            end

            k=1;
            disp(' ');
            disp(' Channels   Cross-Correlation ');
            for i=1:(num-1)
                for j=(i+1):num
                    fprintf('   %d & %d    Max Abs = %8.4g at %8.4g sec \n',i,j,xmax(k),tmax(k));
                    k=k+1;
                end
            end
        end

        % Value changed function: InputFormatListBox
        function InputFormatListBoxValueChanged(app, event)
             LB_change(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Signal Correlation';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutocorrelationTab = uitab(app.TabGroup);
            app.AutocorrelationTab.Title = 'Autocorrelation';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.AutocorrelationTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = {'The input array must have two columns:  time(sec) & amplitude'; ''};
            app.InputArrayNameEditFieldLabel = uilabel(app.AutocorrelationTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.AutocorrelationTab, 'text');
            app.YaxisLabelEditFieldLabel = uilabel(app.AutocorrelationTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.AutocorrelationTab, 'text');
            app.CalculateButton = uibutton(app.AutocorrelationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CrossCorrelationTab = uitab(app.TabGroup);
            app.CrossCorrelationTab.Title = 'Cross-Correlation';
            app.InputFormatListBoxLabel = uilabel(app.CrossCorrelationTab);
            app.InputFormatListBoxLabel.Text = 'Input Format';
            app.InputFormatListBox = uilistbox(app.CrossCorrelationTab);
            app.InputFormatListBox.Items = {'Common array:  time(sec) & two or more amplitudes', 'Two Separate arrays each: time(sec) & amplitude', '', '', ''};
            app.InputFormatListBox.ValueChangedFcn = createCallbackFcn(app, @InputFormatListBoxValueChanged, true);
            app.InputFormatListBox.Value = 'Common array:  time(sec) & two or more amplitudes';
            app.CalculateButton_2 = uibutton(app.CrossCorrelationTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.FirstArrayNameEditFieldLabel = uilabel(app.CrossCorrelationTab);
            app.FirstArrayNameEditFieldLabel.Text = 'First Array Name';
            app.FirstArrayNameEditField = uieditfield(app.CrossCorrelationTab, 'text');
            app.SecondArrayNameEditFieldLabel = uilabel(app.CrossCorrelationTab);
            app.SecondArrayNameEditFieldLabel.Text = 'Second Array Name';
            app.SecondArrayNameEditField = uieditfield(app.CrossCorrelationTab, 'text');
            app.YaxisLabelEditField_2Label = uilabel(app.CrossCorrelationTab);
            app.YaxisLabelEditField_2Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField_2 = uieditfield(app.CrossCorrelationTab, 'text');
            app.signal_correlationver11byTomIrvineLabel = uilabel(app.UIFigure);
            app.signal_correlationver11byTomIrvineLabel.Text = {'signal_correlation  ver 1.1  by Tom Irvine'; ''};
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_correlation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end