classdef signal_correlation_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        signal_correlation_aver11byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AutocorrelationTab             matlab.ui.container.Tab
        EndTimesecEditField_2          matlab.ui.control.NumericEditField
        EndTimesecEditField_2Label     matlab.ui.control.Label
        StartTimesecEditField_2        matlab.ui.control.NumericEditField
        StartTimesecEditField_2Label   matlab.ui.control.Label
        SignalProcessingListBox_auto   matlab.ui.control.ListBox
        SignalProcessingListBoxLabel_auto  matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        listbox_method                 matlab.ui.control.ListBox
        text1                          matlab.ui.control.Label
        CalculateButton_auto           matlab.ui.control.Button
        YaxisLabelEditField            matlab.ui.control.EditField
        YaxisLabelEditFieldLabel       matlab.ui.control.Label
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        CrossCorrelationTab            matlab.ui.container.Tab
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        SelectSecondInputArrayListBox  matlab.ui.control.ListBox
        SelectSecondInputArrayListBoxLabel  matlab.ui.control.Label
        SelectInputArrayListBox_first  matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel_first  matlab.ui.control.Label
        UpdateListButton_2             matlab.ui.control.Button
        text1_3                        matlab.ui.control.Label
        ListboxFormat                  matlab.ui.control.ListBox
        text1_2                        matlab.ui.control.Label
        ListboxMethod                  matlab.ui.control.ListBox
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        YaxisLabelEditField_2          matlab.ui.control.EditField
        YaxisLabelEditField_2Label     matlab.ui.control.Label
        CalculateButton_cross          matlab.ui.control.Button
    end

    
    properties (Access = private)
        nMethoda  %
        nMethodc  %       
        THM       %
        nTCa      %
        nTCc      %
        nFormat   %
    end
    
    methods (Access = private)

        function external_array_3C(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end

        end    

        function external_array_2C(app)
              
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
                fid = fopen(filename,'r');
                app.THM = fscanf(fid,'%g %g',[2 inf]);
                app.THM=app.THM';
                size(app.THM)

                msgbox('File read');              

            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 1')
            end

            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 2')
            end

        end    
       
        function Trans_change(app)

            clear(app);

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [app.nMethodc]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [app.nFormat]=find_index(Items,im);

            fprintf(' app.nMethodc=%d   app.nFormat=%d \n',app.nMethodc,app.nFormat);

            app.SelectInputArrayListBox.Visible='off';
            app.SelectInputArrayListBoxLabel.Visible='off';   
            app.SelectSecondInputArrayListBox.Visible='off';
            app.SelectSecondInputArrayListBoxLabel.Visible='off';  

            if(app.nMethodc==2)
                if(app.nFormat==1)
                    external_array_3C(app);
                else    
                    external_array_2C(app);
                end    
            end    

            app.SelectInputArrayListBox.Visible='on';
            app.SelectInputArrayListBoxLabel.Visible='on'; 
                
            if(app.nFormat==1)
                ss=sprintf('Select Common Input Array');
            else
                ss=sprintf('Select First Input Array');
                app.SelectSecondInputArrayListBox.Visible='on';
                app.SelectSecondInputArrayListBoxLabel.Visible='on';                
            end
            app.SelectInputArrayListBoxLabel_first.Text=ss; 
            update_two(app);
            app.ListboxMethod.Value=app.ListboxMethod.Items{1};

        end        

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        function update_two(app)
            clear(app);
            cell_array=update_time_history_23();
            app.SelectInputArrayListBox_first.Items=unique(sort(cell_array));
            app.SelectSecondInputArrayListBox.Items=unique(sort(cell_array));            
        end
        function update(app)
            clear(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox_first.Items=unique(sort(cell_array)); 
        end
        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end    
        function TimeChoice(app)

            app.EndTimesecEditField.Visible='off';
            app.EndTimesecEditFieldLabel.Visible='off';
            app.EndTimesecEditField_2.Visible='off';
            app.EndTimesecEditField_2Label.Visible='off';   

            app.StartTimesecEditField.Visible='off';
            app.StartTimesecEditFieldLabel.Visible='off';
            app.StartTimesecEditField_2.Visible='off';
            app.StartTimesecEditField_2Label.Visible='off';              

            Items = char(app.SignalProcessingListBox.Items);
            im=app.SignalProcessingListBox.Value;
            [nc]=find_index(Items,im);
            if(nc==2)
                app.nTCc=2;
                app.EndTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';      
                app.StartTimesecEditField.Visible='on';
                app.StartTimesecEditFieldLabel.Visible='on';               
            end

            Items = char(app.SignalProcessingListBox_auto.Items);
            im=app.SignalProcessingListBox_auto.Value;
            [na]=find_index(Items,im);
            if(na==2)
                app.nTCa=2;
                app.EndTimesecEditField_2.Visible='on';
                app.EndTimesecEditField_2Label.Visible='on';   
                app.StartTimesecEditField_2.Visible='on';
                app.StartTimesecEditField_2Label.Visible='on';                 
            end

        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
            update(app);
            update_two(app);
            ClosePlots(app);
            TimeChoice(app);
            Trans_change(app);

        end

        % Button pushed function: CalculateButton_auto
        function CalculateButton_autoPushed(app, event)
                
            ClosePlots(app);
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethoda]=find_index(Items,im);

            if(app.nMethoda==1)
                 try
                     FS=app.SelectInputArrayListBox.Value
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            sz=size(app.THM);
            
            if(sz(2)~=2)
               warndlg('Input array must have two columns');
               return;
            end

            if(app.nTCa==2)
                tstart=app.StartTimesecEditField_2.Value;
                tend=app.EndTimesecEditField_2.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:); 
            end

            [app.THM,iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(iflag==999)
                return;
            end
            
            t=app.THM(:,1);
            amp=app.THM(:,2);
            dt=mean(diff(t));
            num=length(t);

            [xc,xmax,tmax]=cross_correlation_function_alt(amp,amp,num,dt);
            
            YS=app.YaxisLabelEditField.Value;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Time History');
            xlabel(' Time(sec) ')
            ylabel(YS)
            grid on;

            stmax=strtrim(sprintf('%7.3g',tmax));

            %
            fprintf('\n Maximum occurs at %s sec   Amp = %7.3g \n',stmax,xmax);
            %
            figure(2);
            plot(xc(:,1),xc(:,2));
            xlabel(' Delay(sec) ');
            ylabel('Rxx')
            out1=sprintf(' Autocorrelation   Max at %s sec   Amp=%7.3g',stmax,xmax);
            title(out1);
            grid on;
            %
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq
        end

        % Button pushed function: CalculateButton_cross
        function CalculateButton_crossPushed(app, event)
            fig_num=1;
            
            ClosePlots(app);
        
            YS=app.YaxisLabelEditField.Value;
        
            %%%%%
            
            if(app.nFormat==1)
                try
                    FS=app.SelectInputArrayListBox_first.Value;
                    app.THM=evalin('base',FS);
                    data1=[app.THM(:,1) app.THM(:,2)];
                    data2=[app.THM(:,1) app.THM(:,3)];       
                catch
                    warndlg('Input Array 1 does not exist.  Try reading in external file.')
                    return;
                end
            else
                try
                    FS=app.SelectInputArrayListBox_first.Value;
                    F1=evalin('base',FS);
                    data1=F1;
                catch
                    warndlg('Input Array 1 does not exist.  Try reading in external file.')
                    return;
                end

                try
                    FS=app.SelectSecondInputArrayListBox.Value;
                    F2=evalin('base',FS);
                    data2=F2;
                catch
                    warndlg('Input Array 2 does not exist.  Try reading in external file.')
                    return;
                end                    

                try
                    app.THM=[data1 data2(:,2)];
                catch
                    warndlg('Arrays not compatible');
                    return;
                end    
            end    

            if(app.nTCc==2)
                tstart=app.StartTimesecEditField_2.Value;
                tend=app.EndTimesecEditField_2.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:); 
            end         

            %%%%%

            xlabelx='Time(sec)';

            ylabel1=YS;
            ylabel2=YS;
            t_string1='Channel 1';
            t_string2='Channel 2';
            [fig_num]=subplots_two_linlin_titles(fig_num,xlabelx,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
                        
            %%%

            if(app.nFormat==1)
               dt=mean(diff(data1(:,1)));
            else
               num1=length(data1(:,1));
               dt1=mean(diff(data1(:,1)));
            %   
               num2=length(data2(:,1));
               dt2=mean(diff(data2(:,1)));  
            %
            %  Truncate if necessary
            %
                if(num1<num2)
                    num2=num1;
                end
                if(num2<num1)
                    num1=num2;
                end
                num=num1;
            %
                pe=abs((dt1-dt2)/dt1);
            %
                dt=(dt1+dt2)/2;
            %
                if(pe>0.01)
                    fprintf('Warning: dt1=%8.4g  dt2=%8.4g \n',dt1,dt2);
                    warndlg('time steps differ');
                end 
            %
            end

           if(app.nTCc==2)
                tstart=app.StartTimesecEditField.Value;
                tend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-data1(:,1)));      
                [~,iend]=min(abs(tend-data1(:,1)));      
                data1=data1(istart:iend,:); 
      
                [~,istart]=min(abs(tstart-data2(:,1)));      
                [~,iend]=min(abs(tend-data2(:,1)));      
                data2=data2(istart:iend,:); 
            end          

            np=length(data1(:,1));

            k=1;

        %    for i=1:(num-1)
        %        for j=(i+1):num

                    a=data1(:,2);
                    b=data2(:,2);
          
                    [xc,xmax(k),tmax(k)]=cross_correlation_function(a,b,np,dt);
        
                    [rho(k)]=Pearson_coefficient(a,b);   

                    figure(fig_num);
                    fig_num=fig_num+1;
                    plot(a,b,'bo','MarkerSize',3);
                    grid on;
                    i=1;
                    j=1;
                    xL=sprintf('Channel %d Amplitude',i);        
                    yL=sprintf('Channel %d Amplitude',j);
                    xlabel(xL);
                    ylabel(yL);
                    out2=sprintf('Scatter Plot, Pearson Coefficient= %6.3g',rho(k));
                    title(out2);

                    xl = xlim;
                    xx=max(abs(xl));
                    xlim([-xx xx]);
                    yl = ylim;
                    yy=max(abs(yl));
                    ylim([-yy yy]);                
                    axis square;

                    figure(fig_num);
                    fig_num=fig_num+1;
                    plot(xc(:,1),xc(:,2));
                    xlabel('Delay(sec)');
                    ylabel('Rxy');
                    out1=sprintf(' Cross-correlation Channels %d & %d \n Max Abs = %8.4g at %8.4g sec',i,j,xmax(k),tmax(k));
                    title(out1);     
                    grid on;

                    num=2;

                    if(num==2)
                        disp(' ');
                        disp(' Covariance Matrix: ');
                        disp(' ');
                        cvm = cov(a,b);
                    end
                    k=k+1;


                    ss1=sprintf('%7.3g \t %7.3g',cvm(1,1),cvm(1,2));
                    ss2=sprintf('%7.3g \t %7.3g',cvm(2,1),cvm(2,2));

                    ss=sprintf('Covariance Array \n\n %s \t %s',ss1,ss2);
                    app.ResultsTextArea.Value=ss;
                    app.ResultsTextArea.Visible='on';
                    app.ResultsTextAreaLabel.Visible='on';

       %         end
       %     end

            k=1;
            disp(' ');
            disp(' Channels   Cross-Correlation ');
        
            i=1;
            j=1;

            fprintf('   %d & %d    Max Abs = %8.4g at %8.4g sec \n',i,j,xmax(k),tmax(k));
            k=k+1;

        end

        % Callback function
        function InputFormatListBoxValueChanged(app, event)
             LB_change(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethoda]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Value changed function: SignalProcessingListBox_auto
        function SignalProcessingListBox_autoValueChanged(app, event)
            TimeChoice(app);
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            TimeChoice(app);
        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed(app, event)
            update_two(app);
        end

        % Value changed function: ListboxMethod
        function ListboxMethodValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat
        function ListboxFormatValueChanged(app, event)
            Trans_change(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Signal Correlation';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutocorrelationTab = uitab(app.TabGroup);
            app.AutocorrelationTab.Title = 'Autocorrelation';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.AutocorrelationTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = {'The input array must have two columns:  time(sec) & amplitude'; ''};
            app.YaxisLabelEditFieldLabel = uilabel(app.AutocorrelationTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.AutocorrelationTab, 'text');
            app.CalculateButton_auto = uibutton(app.AutocorrelationTab, 'push');
            app.CalculateButton_auto.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_autoPushed, true);
            app.CalculateButton_auto.Text = 'Calculate';
            app.text1 = uilabel(app.AutocorrelationTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.AutocorrelationTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.UpdateListButton = uibutton(app.AutocorrelationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.AutocorrelationTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.AutocorrelationTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SignalProcessingListBoxLabel_auto = uilabel(app.AutocorrelationTab);
            app.SignalProcessingListBoxLabel_auto.Text = 'Signal Processing';
            app.SignalProcessingListBox_auto = uilistbox(app.AutocorrelationTab);
            app.SignalProcessingListBox_auto.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox_auto.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBox_autoValueChanged, true);
            app.SignalProcessingListBox_auto.Value = 'Entire Signal';
            app.StartTimesecEditField_2Label = uilabel(app.AutocorrelationTab);
            app.StartTimesecEditField_2Label.Text = 'Start Time (sec)';
            app.StartTimesecEditField_2 = uieditfield(app.AutocorrelationTab, 'numeric');
            app.EndTimesecEditField_2Label = uilabel(app.AutocorrelationTab);
            app.EndTimesecEditField_2Label.Text = 'End Time (sec)';
            app.EndTimesecEditField_2 = uieditfield(app.AutocorrelationTab, 'numeric');
            app.CrossCorrelationTab = uitab(app.TabGroup);
            app.CrossCorrelationTab.Title = 'Cross-Correlation';
            app.CalculateButton_cross = uibutton(app.CrossCorrelationTab, 'push');
            app.CalculateButton_cross.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_crossPushed, true);
            app.CalculateButton_cross.Text = 'Calculate';
            app.YaxisLabelEditField_2Label = uilabel(app.CrossCorrelationTab);
            app.YaxisLabelEditField_2Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField_2 = uieditfield(app.CrossCorrelationTab, 'text');
            app.SignalProcessingListBoxLabel = uilabel(app.CrossCorrelationTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.CrossCorrelationTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.CrossCorrelationTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.CrossCorrelationTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.CrossCorrelationTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.CrossCorrelationTab, 'numeric');
            app.ListboxMethod = uilistbox(app.CrossCorrelationTab);
            app.ListboxMethod.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod.ValueChangedFcn = createCallbackFcn(app, @ListboxMethodValueChanged, true);
            app.ListboxMethod.Tag = 'listbox_method';
            app.ListboxMethod.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.CrossCorrelationTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.ListboxFormat = uilistbox(app.CrossCorrelationTab);
            app.ListboxFormat.Items = {'Common array:  time(sec) & amplitude & amplitude', 'Two separate arrays, each:  time(sec) & amplitude'};
            app.ListboxFormat.ValueChangedFcn = createCallbackFcn(app, @ListboxFormatValueChanged, true);
            app.ListboxFormat.Tag = 'listbox_method';
            app.ListboxFormat.Value = 'Two separate arrays, each:  time(sec) & amplitude';
            app.text1_3 = uilabel(app.CrossCorrelationTab);
            app.text1_3.Tag = 'text1';
            app.text1_3.Text = 'Select Data Input Format';
            app.UpdateListButton_2 = uibutton(app.CrossCorrelationTab, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.SelectInputArrayListBoxLabel_first = uilabel(app.CrossCorrelationTab);
            app.SelectInputArrayListBoxLabel_first.Text = 'Select Input Array';
            app.SelectInputArrayListBox_first = uilistbox(app.CrossCorrelationTab);
            app.SelectInputArrayListBox_first.Items = {};
            app.SelectInputArrayListBox_first.Value = {};
            app.SelectSecondInputArrayListBoxLabel = uilabel(app.CrossCorrelationTab);
            app.SelectSecondInputArrayListBoxLabel.Text = 'Select Second Input Array';
            app.SelectSecondInputArrayListBox = uilistbox(app.CrossCorrelationTab);
            app.SelectSecondInputArrayListBox.Items = {};
            app.SelectSecondInputArrayListBox.Value = {};
            app.ResultsTextAreaLabel = uilabel(app.CrossCorrelationTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.CrossCorrelationTab);
            app.signal_correlation_aver11byTomIrvineLabel = uilabel(app.UIFigure);
            app.signal_correlation_aver11byTomIrvineLabel.Text = {'signal_correlation_a  ver 1.1  by Tom Irvine'; ''};
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_correlation_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end