classdef signal_editing_ultillites_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputStatisticsTextArea        matlab.ui.control.TextArea
        OutputStatisticsTextAreaLabel   matlab.ui.control.Label
        ShiftTimeforStartatT0CheckBox   matlab.ui.control.CheckBox
        PlotInputButton                 matlab.ui.control.Button
        OutputSignalNameEditField       matlab.ui.control.EditField
        OutputSignalNameEditFieldLabel  matlab.ui.control.Label
        InputYaxisLabelEditField        matlab.ui.control.EditField
        InputYaxisLabelEditFieldLabel   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PerformAnalysisButton           matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        aTab                            matlab.ui.container.Tab
        AmplitudeScaleFactorEditField   matlab.ui.control.EditField
        AmplitudeScaleFactorLabel       matlab.ui.control.Label
        SelectTrendRemovalListBox       matlab.ui.control.ListBox
        SelectTrendRemovalListBoxLabel  matlab.ui.control.Label
        MultiplyAmplitudebyScaleFactorListBox  matlab.ui.control.ListBox
        MultiplyAmplitudebyScaleFactorLabel  matlab.ui.control.Label
        OutputYaxisLabelEditField       matlab.ui.control.EditField
        InputYaxisLabelEditField_2Label  matlab.ui.control.Label
        TrendRemovalAmplitudeScalingLabel  matlab.ui.control.Label
        bTab                            matlab.ui.container.Tab
        SegmentTimesecLabel             matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        ExtractSegmentLabel             matlab.ui.control.Label
        cTab                            matlab.ui.container.Tab
        HighFreqHzEditField             matlab.ui.control.EditField
        HighFreqHzEditFieldLabel        matlab.ui.control.Label
        DownsampleFactorListBox         matlab.ui.control.ListBox
        DownsampleFactorLabel           matlab.ui.control.Label
        FilterMessageLabel              matlab.ui.control.Label
        LowPassFreqHzEditField          matlab.ui.control.EditField
        LowPassFreqHzEditFieldLabel     matlab.ui.control.Label
        ApplyFilterPriortoDownsamplingListBox  matlab.ui.control.ListBox
        ApplyFilterPriortoDownsamplingLabel  matlab.ui.control.Label
        NewSampleRateHzEditField        matlab.ui.control.EditField
        NewSampleRateHzEditFieldLabel   matlab.ui.control.Label
        CurrentSampleRateHzEditField    matlab.ui.control.EditField
        CurrentSampleRateHzEditFieldLabel  matlab.ui.control.Label
        DecimateDownsampleLabel         matlab.ui.control.Label
        dTab                            matlab.ui.container.Tab
        NewSampleRateHzEditField_2      matlab.ui.control.EditField
        NewSampleRateHzEditField_2Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_2  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_2Label  matlab.ui.control.Label
        CubicSplineCurvefitLabel        matlab.ui.control.Label
        eTab                            matlab.ui.container.Tab
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        AddTimeColumnLabel              matlab.ui.control.Label
        fTab                            matlab.ui.container.Tab
        EnterTotalDurationsecEditField  matlab.ui.control.EditField
        EnterTotalDurationsecEditFieldLabel  matlab.ui.control.Label
        SelectPaddingMethodListBox      matlab.ui.control.ListBox
        SelectPaddingMethodListBoxLabel  matlab.ui.control.Label
        AddTrailingZerosLabel           matlab.ui.control.Label
        gTab                            matlab.ui.container.Tab
        NewSampleRateHzEditField_3      matlab.ui.control.EditField
        NewSampleRateHzEditField_3Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_3  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_3Label  matlab.ui.control.Label
        LinearInterpolationLabel        matlab.ui.control.Label
        hTab                            matlab.ui.container.Tab
        StatisticsTextArea              matlab.ui.control.TextArea
        StatisticsTextAreaLabel         matlab.ui.control.Label
        EnterSigmaEditField             matlab.ui.control.EditField
        EnterSigmaEditFieldLabel        matlab.ui.control.Label
        SelectWindowListBox             matlab.ui.control.ListBox
        SelectWindowListBoxLabel        matlab.ui.control.Label
        ViewEquationsButton             matlab.ui.control.Button
        ApplyWindowLabel                matlab.ui.control.Label
        iTab                            matlab.ui.container.Tab
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        AmplitudeLimitsLabel            matlab.ui.control.Label
        PeakClipLabel                   matlab.ui.control.Label
        jTab                            matlab.ui.container.Tab
        MinEditField_2                  matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        AmplitudeLimitsLabel_2          matlab.ui.control.Label
        NewSampleRateHzEditField_4      matlab.ui.control.EditField
        NewSampleRateHzEditField_4Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_4  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_4Label  matlab.ui.control.Label
        ClippedDataCubicSplineFitLabel  matlab.ui.control.Label
        signal_editing_utilities_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        kflag %
        listbox_choice    %
        n_amplitude_scale %
    end
    
    methods (Access = private)

        function PadChange(app)
            Items = char(app.SelectPaddingMethodListBox.Items);
            im=app.SelectPaddingMethodListBox.Value;
            [LM]=find_index(Items,im);

            if(LM==1)
                ss=sprintf('Enter Total Duration (sec)');
            else
                ss=sprintf('Enter Total Number of Points');
            end
            app.EnterTotalDurationsecEditFieldLabel.Text=ss;

        end    

        function CurrentSampleRate(app)
            preprocess(app);
        end    

        function FilterMessage(app)
       
            csr=str2num(app.CurrentSampleRateHzEditField.Value);
            
            nfactor=str2num(app.DownsampleFactorListBox.Value);
            
            nsr=csr/nfactor;

            app.NewSampleRateHzEditField.Value=sprintf('%8.4g',nsr);
            
            ss=sprintf('Filter frequency should be < %9.4g Hz',0.8*nsr/2);
            app.FilterMessageLabel.Text=ss;
        end    

        function FilterOption(app)
            Items = char(app.ApplyFilterPriortoDownsamplingListBox.Items);
            im=app.ApplyFilterPriortoDownsamplingListBox.Value;
            [n]=find_index(Items,im);

            app.LowPassFreqHzEditField.Visible='off';
            app.LowPassFreqHzEditFieldLabel.Visible='off';
            
            app.HighFreqHzEditField.Visible='off';
            app.HighFreqHzEditFieldLabel.Visible='off';            

            if(n==1)
                app.LowPassFreqHzEditField.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Text='Low Pass Freq (Hz)';
            end
            if(n==2)
                app.LowPassFreqHzEditField.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Text='Low Freq (Hz)';
                app.HighFreqHzEditField.Visible='on';
                app.HighFreqHzEditFieldLabel.Visible='on';              
            end
   
        end    

        function ScaleFactor(app)
            Items = char(app.MultiplyAmplitudebyScaleFactorListBox.Items);
            im=app.MultiplyAmplitudebyScaleFactorListBox.Value;
            [app.n_amplitude_scale]=find_index(Items,im);

            if(app.n_amplitude_scale==1)
                app.AmplitudeScaleFactorEditField.Visible='off';
                app.AmplitudeScaleFactorLabel.Visible='off';           
            else
                app.AmplitudeScaleFactorEditField.Visible='on';
                app.AmplitudeScaleFactorLabel.Visible='on';              
            end
        end    

        function AnalysisChange(app)
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            app.TabGroup.SelectedTab=app.TabGroup.Children(n);

            CurrentSampleRate(app);   
 
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            app.OutputStatisticsTextArea.Visible='off';     
            app.OutputStatisticsTextAreaLabel.Visible='off';             
        end
        
        function update(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || ...
                    contains(vars(i).name,'vname_list') || ...
                    contains(vars(i).name,'app.amp_cycles') || ...
                    contains(vars(i).name,'range_cycles')  )
 
                    cell_array{i} ='999';
                else  

                    try
               
                        TTT=evalin('base',vars(i).name);
                        sz=size(TTT);

                        if(sz(1)>=3 && (sz(2)==1 || sz(2)==2))
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end
       
            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            app.kflag=0;  
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));          

        end

        function preprocess(app)
            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [m]=find_index(Items,im);           
            app.listbox_choice=m;
            
           
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            AI = char(app.SelectInputArrayListBox.Items);
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     try
                        app.SelectInputArrayListBox.Value=AI(1,:); 
                        FS=app.SelectInputArrayListBox.Value;
                        app.THM=evalin('base',FS);
                     catch    
                        msgbox('Select Input Array')
                        return;
                     end   
                 end
            end     

            sz=size(app.THM);

            if(sz(1)<=1 || sz(2)==0 )
                warndlg('Read Data Failed');
                return;
            end

            if(app.ShiftTimeforStartatT0CheckBox.Value)
                app.THM(:,1)=app.THM(:,1)-app.THM(1,1);
            end    

            try
                t=app.THM(:,1);
                y=app.THM(:,2);
            
                n=length(y);
                dt=(t(n)-t(1))/(n-1);
            
                csr=1/dt;
                css=sprintf('%10.4g',csr);

                app.CurrentSampleRateHzEditField.Value=css;
                app.CurrentSampleRateHzEditField_2.Value=css;            
                app.CurrentSampleRateHzEditField_3.Value=css; 
                app.CurrentSampleRateHzEditField_4.Value=css;
            catch
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            ClosePlots(app);
            AnalysisChange(app);
            ScaleFactor(app);
            FilterOption(app);        
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            try
                closereq(app);
            catch
            end
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   app.FS=filename;
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            disp('ref 1')
            CurrentSampleRate(app);

            m=app.TabGroup.SelectedTab;
            n=m.Title;

            if(strcmp(n,'a'))
                n=1;
            end              
            if(strcmp(n,'b'))
                n=2;
            end     
            if(strcmp(n,'c'))
                n=3;
            end              
            if(strcmp(n,'d'))
                n=4;
            end 
            if(strcmp(n,'e'))
                n=5;
            end              
            if(strcmp(n,'f'))
                n=6;
            end     
            if(strcmp(n,'g'))
                n=7;
            end              
            if(strcmp(n,'h'))
                n=8;
            end   
            if(strcmp(n,'i'))
                n=9;
            end      
            if(strcmp(n,'j'))
                n=10;
            end              

            app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
            app.listbox_choice=n;
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
             AnalysisChange(app);
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
             AnalysisChange(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            PlotInputButtonPushed(app, event);

            preprocess(app);
            ppp=1;

            YS=app.InputYaxisLabelEditField.Value;

            sz=size(app.THM);

            if(sz(2)==1)
                y=app.THM(:,1);
            else    
                t=app.THM(:,1);
                y=app.THM(:,2);
                to=t;
                yo=y;
            end

            fprintf('\n app.listbox_choice=%d\n',app.listbox_choice);

            if(app.listbox_choice==1) % Trend Removal & Amplitude Scaling
                if(app.n_amplitude_scale==2)
                    scale=str2num(app.AmplitudeScaleFactorEditField.Value);
                    y=scale*y;
                end

                Items = char(app.SelectTrendRemovalListBox.Items);
                im=app.SelectTrendRemovalListBox.Value;
                [n]=find_index(Items,im);

                if(n==1) % Mean
                    y=y-mean(y);
                end    
                if(n==2) % First-order
                    y=detrend(y);
                end    
                if(n==3) % Second-order
                    p = polyfit(t,y,2);
                    y= y-(p(1)*t.*t + p(2)*t + p(3));               
                end         

            end 
            if(app.listbox_choice==2) % Extract Segment
                ts=str2num(app.StartEditField.Value);
                te=str2num(app.EndEditField.Value);
                [t,y,dt,n]=extract_function(app.THM,ts,te);
            end     
            if(app.listbox_choice==3) % Decimate, Downsample
                FilterMessage(app);
                
                Items = char(app.DownsampleFactorListBox.Items);
                im=app.DownsampleFactorListBox.Value;
                [n]=find_index(Items,im);
                nfactor=n+1;

                Items = char(app.ApplyFilterPriortoDownsamplingListBox.Items);
                im=app.ApplyFilterPriortoDownsamplingListBox.Value;
                [nfilter]=find_index(Items,im);
                
                if(nfilter<=2)
                    iphase=1;
                    csr=str2num(app.CurrentSampleRateHzEditField.Value);
                    dt=1/csr;
                    
                    value_low = str2num(app.LowPassFreqHzEditField.Value);
                    
                    if isempty(value_low)
                        msgbox('Enter lowpass frequency')
                        return;
                    end     
                    
                end

                if(nfilter==1)
                    
                    fc=value_low;
                    
                %    iband:   1=lowpass  2=highpass  3=bandpass 
                %
                %    iphase=1  for refiltering for phase correction
                %          =2  for no refiltering    
                
                    iband=1;
                
                    fl=fc;
                    fh=fc;
                    
                    [y,mu,sd,rms]=Butterworth_filter_function_alt(y,dt,iband,fl,fh,iphase);    
                   
                end
                if(nfilter==2)
                    
                    iband=3;
                    
                    value_high = str2num(app.HighFreqHzEditField.Value);
                    
                    if isempty(value_high)
                        msgbox('Enter high frequency')
                        return;
                    end     
                    
                    fh=str2num(value_low);
                    fl=str2num(value_high);
                    
                    [y,mu,sd,rms]=Butterworth_filter_function_alt(y,dt,iband,fl,fh,iphase);  
                    
                end    
                
                tn=t(1:nfactor:end);
                yn=y(1:nfactor:end);
                
                t = fix_size(tn);
                y = fix_size(yn);
                
            end 
            if(app.listbox_choice==4) % Cubic Spline Curve-fit
                
                n = length(t);
                
                sr=str2num(app.NewSampleRateHzEditField_2.Value);
                
                dt=1/sr;
                %
                np=round((t(n)-t(1))/dt);
                np=np+1;
                %
                xx=linspace(t(1),t(n),np); 
                %
                %
                yy = spline(t,y,xx);
                
                t=fix_size(xx);
                y=fix_size(yy);

                figure(3)
                plot(to,yo,t,y);
                title('Time Histories');
                xlabel(' Time(sec) ')
                legend('Input','Output');
                ylabel(YS);
                grid on;  

                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);

            end   
            if(app.listbox_choice==5) % Add Time Column
                sr=str2num(app.SampleRateHzEditField.Value);
                dt=1/sr;
                sz=size(app.THM);
                t=((1:sz(1))-1)*dt;
                t=fix_size(t);
            end 
            if(app.listbox_choice==6) % Add Trailing Zeros

                Items = char(app.SelectPaddingMethodListBox.Items);
                im=app.SelectPaddingMethodListBox.Value;
                [LM]=find_index(Items,im);

                sz=size(app.THM);
                n=sz(1);

                dt=mean(diff(app.THM(:,1)));
                dur=app.THM(end,1)-app.THM(1,1);
            
                num=str2num(app.EnterTotalDurationsecEditField.Value);
                
                if(LM==1)  %  Total Duration
                    
                    if(num<=dur)
                       warndlg('Total duration must be > input duration'); 
                       return; 
                    end
                    
                    m=round(num/dt);
                        
                else       %  Total Points
                    
                    if(num<=n)
                       warndlg('Total points must be > input points'); 
                       return; 
                    end
                    
                    m=num;
                
                end
                
                tt=zeros(m,1);
                tt(1:n)=t(1:n);
                    
                b=zeros(m,1);
                b(1:n)=b(1:n)+y(1:n);
                    
                ijk=1;
                    
                for i=(n+1):m
                    tt(i)=(ijk*dt)+t(n);
                    ijk=ijk+1;
                end

                t=fix_size(tt);
                y=fix_size(b);
                
            end     
            if(app.listbox_choice==7) % Linear Interpolation
                sr=str2num(app.NewSampleRateHzEditField_3.Value);

                dt=1/sr;
                
                tstart=app.THM(1,1);
                tend=app.THM(end,1);
                
                n=floor((tend-tstart)/dt);
                %
                upper=(n*dt)+tstart;
                
                
                fprintf('\n sr=%8.4g  dt=%8.4g  n=%d  tstart=%8.4g  upper=%8.4g \n',sr,dt,n,tstart,upper);
                
                %   This method is more reliable than Matlab's interp1
                
                [t,y]=linear_interpolation(t,y,dt); 
 
            end 
            if(app.listbox_choice==8) % Apply Window
                
            end    
            if(app.listbox_choice==9) % Peak Clip
                L=str2num(app.MinEditField.Value);
                U=str2num(app.MaxEditField.Value);

                sz=size(app.THM);
                
                THM_clip=app.THM;
                
                
                for i=1:sz(1)
                    if(THM_clip(i,2)>U)
                        THM_clip(i,2)=U;
                    end    
                    if(THM_clip(i,2)<L)
                        THM_clip(i,2)=L;
                    end         
                end
                
                xlabel2='Time (sec)';
                
                ylab=YS;

                data1=app.THM;
                data2=THM_clip;
                
                t_string1='Input Data';
                t_string2='Clipped Data';
                
                fig_num=3;
                [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylab,ylab,data1,data2,t_string1,t_string2);

                y=THM_clip(:,2);

            end 
            if(app.listbox_choice==10) % Clipped Data Cubic Spline Fit
                ppp=0;
            end   

            if(ppp==1)
                figure(2)
                plot(t,y);
                title('Output Time History');
                xlabel(' Time(sec) ')
                ylabel(YS);
                grid on;  

                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);

            end  

            name=app.OutputSignalNameEditField.Value;
            assignin('base',name, [t y]); 

            disp(' ');
            disp(' Calculating skewness & kurtosis ');
            
            tim=t;
            amp=y;
            duration=tim(end)-tim(1);
            dt=mean(diff(tim));
            [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Calculating Zero & Peak crossings ');
            
            [pszcr,peak_rate,tpa,pa]=zero_crossing_function_alt(tim,amp,duration);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
           
            string2=sprintf('\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=string2;
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            
            
                mx=max(amp);
                mi=min(amp);
            
                amax=abs(mx);
                amin=abs(mi);
                if(amax<amin)
                    amax=amin;
                end
                crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);
            
            
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            
            [rf]=Rice_frequency(amp,dt);
            
            %
                string_rf=sprintf('\n\n Rice Characteristic Frequency = \n  %8.4g Hz \n',rf);
            
                string_zc=sprintf('\n Positive Slope Zero Cross Rate = \n  %8.4g Hz \n',pszcr);
                string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            disp(string_rf);
            disp(string_zc);
            disp(string_pr);
            
            big_string=sprintf('%s %s',big_string,string_rf);
            big_string=sprintf('%s %s',big_string,string_zc);
            big_string=sprintf('%s %s',big_string,string_pr);
            
            app.OutputStatisticsTextArea.Value=big_string;
            app.OutputStatisticsTextArea.Visible='on';
            app.OutputStatisticsTextAreaLabel.Visible='on';  

            msgbox('Save Complete');            

        end

        % Button pushed function: PlotInputButton
        function PlotInputButtonPushed(app, event)
            
            preprocess(app);

            sz=size(app.THM);

            try
                figure(1)
                
                if(sz(2)==1)
                    plot(app.THM(:,1))
                else    
                    plot(app.THM(:,1),app.THM(:,2));
                    xlabel('Time (sec)');
                end

                title('Input Time History')
                grid on;
                YL=app.InputYaxisLabelEditField.Value;
                ylabel(YL);
                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);
            catch
                warndlg('Input Plot Failed');
                return;
            end
        end

        % Value changed function: MultiplyAmplitudebyScaleFactorListBox
        function MultiplyAmplitudebyScaleFactorListBoxValueChanged(app, event)
            ScaleFactor(app);
            ClosePlots(app);
        end

        % Value changed function: ApplyFilterPriortoDownsamplingListBox
        function ApplyFilterPriortoDownsamplingListBoxValueChanged(app, event)
            FilterOption(app);
            ClosePlots(app);
        end

        % Value changed function: DownsampleFactorListBox
        function DownsampleFactorListBoxValueChanged(app, event)
            FilterMessage(app);
            ClosePlots(app);
        end

        % Value changed function: SelectPaddingMethodListBox
        function SelectPaddingMethodListBoxValueChanged(app, event)
            PadChange(app);
            ClosePlots(app);   
        end

        % Value changing function: AmplitudeScaleFactorEditField
        function AmplitudeScaleFactorEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectTrendRemovalListBox
        function SelectTrendRemovalListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: StartEditField
        function StartEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: EndEditField
        function EndEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: LowPassFreqHzEditField
        function LowPassFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: HighFreqHzEditField
        function HighFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_2
        function NewSampleRateHzEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: EnterTotalDurationsecEditField
        function EnterTotalDurationsecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_3
        function NewSampleRateHzEditField_3ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectWindowListBox
        function SelectWindowListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: EnterSigmaEditField
        function EnterSigmaEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxEditField
        function MaxEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinEditField
        function MinEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_4
        function NewSampleRateHzEditField_4ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxEditField_2
        function MaxEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinEditField_2
        function MinEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.signal_editing_utilities_aver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.signal_editing_utilities_aver10byTomIrvineLabel.Text = 'signal_editing_utilities_a   ver 1.0   by Tom Irvine         The input array must have two columns:  time (sec) & amplitude';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.aTab = uitab(app.TabGroup);
            app.aTab.Title = 'a';
            app.TrendRemovalAmplitudeScalingLabel = uilabel(app.aTab);
            app.TrendRemovalAmplitudeScalingLabel.Text = 'Trend Removal & Amplitude Scaling';
            app.InputYaxisLabelEditField_2Label = uilabel(app.aTab);
            app.InputYaxisLabelEditField_2Label.Text = 'Output Y-axis Label';
            app.OutputYaxisLabelEditField = uieditfield(app.aTab, 'text');
            app.OutputYaxisLabelEditField.Value = 'Accel (G)';
            app.MultiplyAmplitudebyScaleFactorLabel = uilabel(app.aTab);
            app.MultiplyAmplitudebyScaleFactorLabel.Text = {'Multiply Amplitude by '; 'Scale Factor'};
            app.MultiplyAmplitudebyScaleFactorListBox = uilistbox(app.aTab);
            app.MultiplyAmplitudebyScaleFactorListBox.Items = {'no', 'yes'};
            app.MultiplyAmplitudebyScaleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @MultiplyAmplitudebyScaleFactorListBoxValueChanged, true);
            app.MultiplyAmplitudebyScaleFactorListBox.Value = 'no';
            app.SelectTrendRemovalListBoxLabel = uilabel(app.aTab);
            app.SelectTrendRemovalListBoxLabel.Text = 'Select Trend Removal';
            app.SelectTrendRemovalListBox = uilistbox(app.aTab);
            app.SelectTrendRemovalListBox.Items = {'Mean', 'First-order', 'Second-order', 'None'};
            app.SelectTrendRemovalListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTrendRemovalListBoxValueChanged, true);
            app.SelectTrendRemovalListBox.Value = 'Mean';
            app.AmplitudeScaleFactorLabel = uilabel(app.aTab);
            app.AmplitudeScaleFactorLabel.Text = 'Amplitude Scale Factor';
            app.AmplitudeScaleFactorEditField = uieditfield(app.aTab, 'text');
            app.AmplitudeScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @AmplitudeScaleFactorEditFieldValueChanging, true);
            app.AmplitudeScaleFactorEditField.Value = '1';
            app.bTab = uitab(app.TabGroup);
            app.bTab.Title = 'b';
            app.ExtractSegmentLabel = uilabel(app.bTab);
            app.ExtractSegmentLabel.Text = 'Extract Segment';
            app.StartEditFieldLabel = uilabel(app.bTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.bTab, 'text');
            app.StartEditField.ValueChangingFcn = createCallbackFcn(app, @StartEditFieldValueChanging, true);
            app.EndEditFieldLabel = uilabel(app.bTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.bTab, 'text');
            app.EndEditField.ValueChangingFcn = createCallbackFcn(app, @EndEditFieldValueChanging, true);
            app.SegmentTimesecLabel = uilabel(app.bTab);
            app.SegmentTimesecLabel.Text = 'Segment Time (sec)';
            app.cTab = uitab(app.TabGroup);
            app.cTab.Title = 'c';
            app.DecimateDownsampleLabel = uilabel(app.cTab);
            app.DecimateDownsampleLabel.Text = 'Decimate, Downsample';
            app.CurrentSampleRateHzEditFieldLabel = uilabel(app.cTab);
            app.CurrentSampleRateHzEditFieldLabel.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField = uieditfield(app.cTab, 'text');
            app.CurrentSampleRateHzEditField.Editable = 'off';
            app.NewSampleRateHzEditFieldLabel = uilabel(app.cTab);
            app.NewSampleRateHzEditFieldLabel.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField = uieditfield(app.cTab, 'text');
            app.NewSampleRateHzEditField.Editable = 'off';
            app.ApplyFilterPriortoDownsamplingLabel = uilabel(app.cTab);
            app.ApplyFilterPriortoDownsamplingLabel.Text = {'Apply Filter Prior to '; 'Downsampling'};
            app.ApplyFilterPriortoDownsamplingListBox = uilistbox(app.cTab);
            app.ApplyFilterPriortoDownsamplingListBox.Items = {'Yes, Low Pass', 'Yes, Band Pass', 'No'};
            app.ApplyFilterPriortoDownsamplingListBox.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterPriortoDownsamplingListBoxValueChanged, true);
            app.ApplyFilterPriortoDownsamplingListBox.Value = 'Yes, Low Pass';
            app.LowPassFreqHzEditFieldLabel = uilabel(app.cTab);
            app.LowPassFreqHzEditFieldLabel.Text = 'Low Pass Freq (Hz)';
            app.LowPassFreqHzEditField = uieditfield(app.cTab, 'text');
            app.LowPassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowPassFreqHzEditFieldValueChanging, true);
            app.FilterMessageLabel = uilabel(app.cTab);
            app.FilterMessageLabel.Text = 'Filter Message';
            app.DownsampleFactorLabel = uilabel(app.cTab);
            app.DownsampleFactorLabel.Text = {'Downsample '; 'Factor'};
            app.DownsampleFactorListBox = uilistbox(app.cTab);
            app.DownsampleFactorListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.DownsampleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorListBoxValueChanged, true);
            app.DownsampleFactorListBox.Value = '2';
            app.HighFreqHzEditFieldLabel = uilabel(app.cTab);
            app.HighFreqHzEditFieldLabel.Text = 'High Freq (Hz)';
            app.HighFreqHzEditField = uieditfield(app.cTab, 'text');
            app.HighFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @HighFreqHzEditFieldValueChanging, true);
            app.dTab = uitab(app.TabGroup);
            app.dTab.Title = 'd';
            app.CubicSplineCurvefitLabel = uilabel(app.dTab);
            app.CubicSplineCurvefitLabel.Text = 'Cubic Spline Curve-fit';
            app.CurrentSampleRateHzEditField_2Label = uilabel(app.dTab);
            app.CurrentSampleRateHzEditField_2Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_2 = uieditfield(app.dTab, 'text');
            app.CurrentSampleRateHzEditField_2.Editable = 'off';
            app.NewSampleRateHzEditField_2Label = uilabel(app.dTab);
            app.NewSampleRateHzEditField_2Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_2 = uieditfield(app.dTab, 'text');
            app.NewSampleRateHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_2ValueChanging, true);
            app.eTab = uitab(app.TabGroup);
            app.eTab.Title = 'e';
            app.AddTimeColumnLabel = uilabel(app.eTab);
            app.AddTimeColumnLabel.Text = 'Add Time Column';
            app.SampleRateHzEditFieldLabel = uilabel(app.eTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.eTab, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.fTab = uitab(app.TabGroup);
            app.fTab.Title = 'f';
            app.AddTrailingZerosLabel = uilabel(app.fTab);
            app.AddTrailingZerosLabel.Text = 'Add Trailing Zeros';
            app.SelectPaddingMethodListBoxLabel = uilabel(app.fTab);
            app.SelectPaddingMethodListBoxLabel.Text = 'Select Padding Method';
            app.SelectPaddingMethodListBox = uilistbox(app.fTab);
            app.SelectPaddingMethodListBox.Items = {'Total Duration', 'Total Points'};
            app.SelectPaddingMethodListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPaddingMethodListBoxValueChanged, true);
            app.SelectPaddingMethodListBox.Value = 'Total Duration';
            app.EnterTotalDurationsecEditFieldLabel = uilabel(app.fTab);
            app.EnterTotalDurationsecEditFieldLabel.Text = 'Enter Total Duration (sec)';
            app.EnterTotalDurationsecEditField = uieditfield(app.fTab, 'text');
            app.EnterTotalDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @EnterTotalDurationsecEditFieldValueChanging, true);
            app.gTab = uitab(app.TabGroup);
            app.gTab.Title = 'g';
            app.LinearInterpolationLabel = uilabel(app.gTab);
            app.LinearInterpolationLabel.Text = 'Linear Interpolation';
            app.CurrentSampleRateHzEditField_3Label = uilabel(app.gTab);
            app.CurrentSampleRateHzEditField_3Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_3 = uieditfield(app.gTab, 'text');
            app.CurrentSampleRateHzEditField_3.Editable = 'off';
            app.NewSampleRateHzEditField_3Label = uilabel(app.gTab);
            app.NewSampleRateHzEditField_3Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_3 = uieditfield(app.gTab, 'text');
            app.NewSampleRateHzEditField_3.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_3ValueChanging, true);
            app.hTab = uitab(app.TabGroup);
            app.hTab.Title = 'h';
            app.ApplyWindowLabel = uilabel(app.hTab);
            app.ApplyWindowLabel.Text = 'Apply Window';
            app.ViewEquationsButton = uibutton(app.hTab, 'push');
            app.ViewEquationsButton.Text = 'View Equations';
            app.SelectWindowListBoxLabel = uilabel(app.hTab);
            app.SelectWindowListBoxLabel.Text = 'Select Window';
            app.SelectWindowListBox = uilistbox(app.hTab);
            app.SelectWindowListBox.Items = {'Exponential Sigma', 'Exponenital T', 'Hanning'};
            app.SelectWindowListBox.ValueChangedFcn = createCallbackFcn(app, @SelectWindowListBoxValueChanged, true);
            app.SelectWindowListBox.Value = 'Exponenital T';
            app.EnterSigmaEditFieldLabel = uilabel(app.hTab);
            app.EnterSigmaEditFieldLabel.Text = 'Enter Sigma';
            app.EnterSigmaEditField = uieditfield(app.hTab, 'text');
            app.EnterSigmaEditField.ValueChangingFcn = createCallbackFcn(app, @EnterSigmaEditFieldValueChanging, true);
            app.EnterSigmaEditField.Value = '1';
            app.StatisticsTextAreaLabel = uilabel(app.hTab);
            app.StatisticsTextAreaLabel.Text = 'Statistics';
            app.StatisticsTextArea = uitextarea(app.hTab);
            app.StatisticsTextArea.Editable = 'off';
            app.iTab = uitab(app.TabGroup);
            app.iTab.Title = 'i';
            app.PeakClipLabel = uilabel(app.iTab);
            app.PeakClipLabel.Text = 'Peak Clip';
            app.AmplitudeLimitsLabel = uilabel(app.iTab);
            app.AmplitudeLimitsLabel.Text = 'Amplitude Limits';
            app.MaxEditFieldLabel = uilabel(app.iTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.iTab, 'text');
            app.MaxEditField.ValueChangingFcn = createCallbackFcn(app, @MaxEditFieldValueChanging, true);
            app.MinEditFieldLabel = uilabel(app.iTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.iTab, 'text');
            app.MinEditField.ValueChangingFcn = createCallbackFcn(app, @MinEditFieldValueChanging, true);
            app.jTab = uitab(app.TabGroup);
            app.jTab.Title = 'j';
            app.ClippedDataCubicSplineFitLabel = uilabel(app.jTab);
            app.ClippedDataCubicSplineFitLabel.Text = 'Clipped Data Cubic Spline Fit';
            app.CurrentSampleRateHzEditField_4Label = uilabel(app.jTab);
            app.CurrentSampleRateHzEditField_4Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_4 = uieditfield(app.jTab, 'text');
            app.CurrentSampleRateHzEditField_4.Editable = 'off';
            app.NewSampleRateHzEditField_4Label = uilabel(app.jTab);
            app.NewSampleRateHzEditField_4Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_4 = uieditfield(app.jTab, 'text');
            app.NewSampleRateHzEditField_4.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_4ValueChanging, true);
            app.AmplitudeLimitsLabel_2 = uilabel(app.jTab);
            app.AmplitudeLimitsLabel_2.Text = 'Amplitude Limits';
            app.MaxEditField_2Label = uilabel(app.jTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.jTab, 'text');
            app.MaxEditField_2.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_2ValueChanging, true);
            app.MinEditField_2Label = uilabel(app.jTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.jTab, 'text');
            app.MinEditField_2.ValueChangingFcn = createCallbackFcn(app, @MinEditField_2ValueChanging, true);
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.listbox_method = uilistbox(app.UIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIFigure);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SelectAnalysisListBoxLabel = uilabel(app.UIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.UIFigure);
            app.SelectAnalysisListBox.Items = {'Trend Removal & Amplitude Scaling', 'Extract Segment', 'Decimate, Downsample', 'Cubic Spline Curve-fit', 'Add Time Column', 'Add Trailing Zeros', 'Linear Interpolation', 'Apply Window', 'Peak Clip', 'Clipped Data Cubic Spline Fit'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Trend Removal & Amplitude Scaling';
            app.PerformAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InputYaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.InputYaxisLabelEditFieldLabel.Text = 'Input Y-axis Label';
            app.InputYaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.InputYaxisLabelEditField.Value = 'Accel (G)';
            app.OutputSignalNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputSignalNameEditFieldLabel.Text = 'Output Signal Name';
            app.OutputSignalNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputSignalNameEditField.Value = 'signal_out';
            app.PlotInputButton = uibutton(app.UIFigure, 'push');
            app.PlotInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputButtonPushed, true);
            app.PlotInputButton.Text = 'Plot Input';
            app.ShiftTimeforStartatT0CheckBox = uicheckbox(app.UIFigure);
            app.ShiftTimeforStartatT0CheckBox.Text = 'Shift Time for Start at T=0';
            app.OutputStatisticsTextAreaLabel = uilabel(app.UIFigure);
            app.OutputStatisticsTextAreaLabel.Text = 'Output Statistics';
            app.OutputStatisticsTextArea = uitextarea(app.UIFigure);
            app.OutputStatisticsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_editing_ultillites_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end