classdef signal_editing_utilities_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalUtilitiesUIFigure         matlab.ui.Figure
        OutputStatisticsTextArea        matlab.ui.control.TextArea
        OutputStatisticsTextAreaLabel   matlab.ui.control.Label
        PlotInputButton                 matlab.ui.control.Button
        OutputSignalNameEditField       matlab.ui.control.EditField
        OutputSignalNameEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        PerformAnalysisButton           matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ReadInputDataTab                matlab.ui.container.Tab
        InputYaxisLabelEditField        matlab.ui.control.EditField
        InputYaxisLabelEditFieldLabel   matlab.ui.control.Label
        ShiftTimeforStartatT0CheckBox   matlab.ui.control.CheckBox
        UpdateListButton                matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        aTab                            matlab.ui.container.Tab
        PlotCumulativeHistogramCheckBox  matlab.ui.control.CheckBox
        PlotTimeHistoryEnvelopeCheckBox  matlab.ui.control.CheckBox
        PlotAbsolutePeakHistogramCheckBox  matlab.ui.control.CheckBox
        text6                           matlab.ui.control.Label
        edit_bars                       matlab.ui.control.EditField
        bTab_3                          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        PercentOverlapListBox           matlab.ui.control.ListBox
        PercentOverlapListBoxLabel      matlab.ui.control.Label
        SegmentDurationsecEditField     matlab.ui.control.EditField
        SegmentDurationsecLabel         matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.EditField
        EndTimesecLabel                 matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.EditField
        StartTimesecLabel               matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SelectFunctionListBox           matlab.ui.control.ListBox
        SelectFunctionListBoxLabel      matlab.ui.control.Label
        cTab_3                          matlab.ui.container.Tab
        AmplitudeScaleFactorEditField   matlab.ui.control.EditField
        AmplitudeScaleFactorLabel       matlab.ui.control.Label
        SelectTrendRemovalListBox       matlab.ui.control.ListBox
        SelectTrendRemovalListBoxLabel  matlab.ui.control.Label
        MultiplyAmplitudebyScaleFactorListBox  matlab.ui.control.ListBox
        MultiplyAmplitudebyScaleFactorLabel  matlab.ui.control.Label
        OutputYaxisLabelEditField       matlab.ui.control.EditField
        InputYaxisLabelEditField_2Label  matlab.ui.control.Label
        TrendRemovalAmplitudeScalingLabel  matlab.ui.control.Label
        dTab_3                          matlab.ui.container.Tab
        SegmentTimesecLabel             matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        ExtractSegmentLabel             matlab.ui.control.Label
        eTab_3                          matlab.ui.container.Tab
        FilteroptionhasrefilteringforphasecorrectionLabel  matlab.ui.control.Label
        HighFreqHzEditField             matlab.ui.control.EditField
        HighFreqHzEditFieldLabel        matlab.ui.control.Label
        DownsampleFactorListBox         matlab.ui.control.ListBox
        DownsampleFactorLabel           matlab.ui.control.Label
        FilterMessageLabel              matlab.ui.control.Label
        LowPassFreqHzEditField          matlab.ui.control.EditField
        LowPassFreqHzEditFieldLabel     matlab.ui.control.Label
        ApplyFilterPriortoDownsamplingListBox  matlab.ui.control.ListBox
        ApplyFilterPriortoDownsamplingLabel  matlab.ui.control.Label
        NewSampleRateHzEditField        matlab.ui.control.EditField
        NewSampleRateHzEditFieldLabel   matlab.ui.control.Label
        CurrentSampleRateHzEditField    matlab.ui.control.EditField
        CurrentSampleRateHzEditFieldLabel  matlab.ui.control.Label
        DecimateDownsampleLabel         matlab.ui.control.Label
        fTab_3                          matlab.ui.container.Tab
        NewSampleRateHzEditField_2      matlab.ui.control.EditField
        NewSampleRateHzEditField_2Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_2  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_2Label  matlab.ui.control.Label
        CubicSplineCurvefitLabel        matlab.ui.control.Label
        gTab_3                          matlab.ui.container.Tab
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        AddTimeColumnLabel              matlab.ui.control.Label
        hTab                            matlab.ui.container.Tab
        EnterTotalDurationsecEditField  matlab.ui.control.EditField
        EnterTotalDurationsecEditFieldLabel  matlab.ui.control.Label
        SelectPaddingMethodListBox      matlab.ui.control.ListBox
        SelectPaddingMethodListBoxLabel  matlab.ui.control.Label
        AddTrailingZerosLabel           matlab.ui.control.Label
        iTab_3                          matlab.ui.container.Tab
        NewSampleRateHzEditField_3      matlab.ui.control.EditField
        NewSampleRateHzEditField_3Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_3  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_3Label  matlab.ui.control.Label
        LinearInterpolationLabel        matlab.ui.control.Label
        jTab_2                          matlab.ui.container.Tab
        EnterSigmaEditField             matlab.ui.control.EditField
        EnterSigmaEditFieldLabel        matlab.ui.control.Label
        SelectWindowListBox             matlab.ui.control.ListBox
        SelectWindowListBoxLabel        matlab.ui.control.Label
        ViewEquationsButton             matlab.ui.control.Button
        ApplyWindowLabel                matlab.ui.control.Label
        kTab_2                          matlab.ui.container.Tab
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        AmplitudeLimitsLabel            matlab.ui.control.Label
        PeakClipLabel                   matlab.ui.control.Label
        lTab                            matlab.ui.container.Tab
        MinEditField_2                  matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        AmplitudeLimitsLabel_2          matlab.ui.control.Label
        NewSampleRateHzEditField_4      matlab.ui.control.EditField
        NewSampleRateHzEditField_4Label  matlab.ui.control.Label
        CurrentSampleRateHzEditField_4  matlab.ui.control.EditField
        CurrentSampleRateHzEditField_4Label  matlab.ui.control.Label
        ClippedDataCubicSplineFitLabel  matlab.ui.control.Label
        mTab                            matlab.ui.container.Tab
        SampleRateMultiplierListBox     matlab.ui.control.ListBox
        SampleRateMultiplierListBoxLabel  matlab.ui.control.Label
        nTab                            matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        CorrectSampleRateHzEditField    matlab.ui.control.EditField
        CorrectSampleRateHzEditFieldLabel  matlab.ui.control.Label
        ApplySampleRateButton           matlab.ui.control.Button
        signal_editing_utilities_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        freq_tv      
        h   
        kflag 
        kurtosis_tv  
        listbox_choice    
        mean_tv      
        n_amplitude_scale 
        peak_tv      
        RMS_tv       
        stddev_tv    
        THM 
        YS  
    end
    
    methods (Access = private)

        function tvfa(app)
            ClosePlots(app);
            
            fig_num=1;
            
            %%%
            
            Items = char(app.PercentOverlapListBox.Items);
            im=app.PercentOverlapListBox.Value;
            [p]=find_index(Items,im);

            po=(p-1)*0.1;
              
            tstart=str2num(app.StartTimesecEditField.Value);
            tend=str2num(app.EndTimesecEditField.Value);
            
            [~,i]=min(abs(tstart-app.THM(:,1)));
            [~,j]=min(abs(tend-app.THM(:,1)));
            
            app.THM=app.THM(i:j,:);
            
            seg=str2num(app.SegmentDurationsecEditField.Value);
            
            if(seg<1.0e-05)
                warndlg('Segment Duration is too low');
                return;
            end
            
            amp=app.THM(:,2);
            tim=app.THM(:,1);
            nn = size(amp);
            n = nn(1);
            %disp(' mean values ')
            mu=mean(amp);
            sd=std(amp);
            mx=max(amp);
            mi=min(amp);
            rms=sqrt(sd^2+mu^2);
            kta=0.;
            tt_max=0.;
            tt_min=0.;
            %1
                for i=1:n
                    if( amp(i)==mx)
                        tt_max=tim(i);
                    end
                    if( amp(i)==mi)
                        tt_min=tim(i);
                    end
                    kta=kta+amp(i)^4;
                end
                kta=kta/(n*sd^4);
            %1
            disp(' ')
            disp(' time stats ')
            disp(' ')
            tmx=max(tim);
            tmi=min(tim);
            % disp(out0)
            out3 = sprintf(' start  = %g sec    end = %g sec            ',tmi,tmx);
            dt=(tmx-tmi)/n;
            sr=1./dt;
            out4 = sprintf(' SR  = %8.4g samples/sec    dt = %8.4g sec            ',sr,dt);
            out5 = sprintf('\n number of samples = %d  ',n);
            disp(out3)
            disp(out4)
            disp(out5)
            disp(' ')
            disp(' amplitude stats ')
            disp(' ')
            out0 = sprintf(' number of points = %d ',n);
            out1 = sprintf(' mean = %8.4g    std = %8.4g    rms = %8.4g ',mu,sd,rms);
            out2a = sprintf(' max  = %9.4g  at  = %8.4g sec            ',mx,tt_max);
            out2b = sprintf(' min  = %9.4g  at  = %8.4g sec            ',mi,tt_min);
            out5  = sprintf('\n kurtosis  = %8.4g ',kta);
            %
            disp(out1)
            disp(out2a)
            disp(out2b)
            disp(out5)
            %
            dur=tmx-tmi;
            %
            if(seg>dur/4)
                seg=dur/4;
                string=sprintf('%8.3g',seg);
                app.SegmentDurationsecEditField.Value=string;
            end
            %
            
            ns=fix(sr*seg);
            
            step=floor(ns*(1-po));
            
            i=1;
            j1=1;
            j2=1+ns;
            
            maxn=1.0e+06;
            
            while(1)
            
                if((j2)>n)
                    break;
                end
            
                j1=j1+step;
                j2=j2+step;
            
                i=i+1;
            
                if(i>maxn)
                    break;
                end
            
            end
            
            nnn=i;
 
            if(nnn==0)
                warndlg('nnn=0');
                return;
            end
            if(step==0)
                warndlg('step=0');
                return;
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            tpi=2*pi;
            
            zc=zeros(nnn,1);
            sds=zeros(nnn,1);
            rms=zeros(nnn,1);
            av=zeros(nnn,1);
            peak=zeros(nnn,1);
            tt=zeros(nnn,1);
            freq=zeros(nnn,1);
            kt=zeros(nnn,1);
            
            %
            j1=1;
            j2=1+ns;
            
            for i=1:nnn
                if(j2>n)
                    break;
                end
            %
                clear x;
                zc(i)=0;
                x=amp(j1:j2);
            
                if(isempty(x))
                    break;
                end
            
            %    peak(i)=max(abs(x));
            
                [mu,sd,xrms,~,xkt]=kurtosis_stats(x);
            
                av(i)=mu;
                sds(i)=sd;
                rms(i)=xrms;
                kt(i)=xkt;
            
                tt(i)=(tim(j1)+tim(j2))/2.;
            %
                for k=2:max(size(x))
                    if(x(k)*x(k-1)<0)
                        zc(i)=zc(i)+1;
                    end
                end
            
            %%%%
            
                nfr=1;
                a=x;
                amp_orig=x;
                t=tim(j1:j2);
                dur=t(end)-t(1);
                tt(i)=mean(t);
            
                [~,~,~,~,omeganr]=sine_find_function(dur,a,amp_orig,t,dt,nfr);
            %    peak(i)=norm([Ar Br]);
            
                peak(i)=max(abs(x));
            
                freq(i)=omeganr/tpi;
            
            %%%%
            
                j1=j1+step;
                j2=j2+step;
            %
            end
            %
            
            freq=fix_size(freq);
            %
            clear length;
            n=length(tt);
            if(tt(n)<tt(n-1))
                tt(n)=[];
                sds(n)=[];
                av(n)=[];
                peak(n)=[];
                rms(n)=[];
                zc(n)=[];
                freq(n)=[];
                kt(n)=[];
            end
            %
            
            for i=1:length(kt)
                if(isnan(kt(i)))
                    kt(i)=0;
                end
            end
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tim,amp)
            xlabel(' Time (sec)');
            ylabel(app.YS);
            grid on;
            title('Time History');
            %
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,kt)
            xlabel(' Time (sec)');
            ylabel('Kurtosis');
            grid on;
            title('Kurtosis Time History');
            %
            
            figure(fig_num);
            fig_num=fig_num+1;
            
            plot(tt,peak,tt,rms,tt,sds,'-.',tt,av);
            
            app.peak_tv=[tt peak];
            app.RMS_tv=[tt rms];
            app.stddev_tv=[tt sds];
            app.mean_tv=[tt av];
            app.kurtosis_tv=[tt kt];
            
            legend ('peak','rms','std dev','average');
            xlabel(' Time (sec)');
            ylabel(app.YS);
            title('Segmented Time History');
            grid;
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,freq);
            setappdata(0,'frequency',[tt freq]);
            title('Frequency vs. Time');
            xlabel(' Time (sec)');
            ylabel(' Freq (Hz)');
            grid;

            app.freq_tv=[tt freq];
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,freq,'.');
            setappdata(0,'frequency',[tt freq]);
            title('Frequency vs. Time');
            xlabel(' Time (sec)');
            ylabel(' Freq (Hz)');
            grid;

            figure(fig_num);
            plot(freq,peak,'.');
            title('Peak vs. Frequency');
            string=sprintf('Peak %s',app.YS);
            ylabel(string);
            xlabel(' Freq (Hz)');
            grid;

            [~,ii]=max(peak);
            
            if(contains(app.YS,'(G)'))
                sss=sprintf(' Maximum absolute peak \n\n = %7.3g G at %8.4g Hz  and %8.4g sec',peak(ii),freq(ii),tt(ii));
            else
                sss=sprintf(' Maximum absolute peak \n\n = %7.3g at %8.4g Hz  and %8.4g sec',peak(ii),freq(ii),tt(ii));
            end
            
            app.OutputStatisticsTextArea.Value=sss;

            app.OutputStatisticsTextArea.Visible='on';
            app.OutputStatisticsTextAreaLabel.Visible='on';   

        end    

        function stats(app)
            ClosePlots(app);

            tstring='Input Signal';
            
            amp=double(app.THM(:,2));
            tim=double(app.THM(:,1));
            t=tim;
            n = length(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                duration=tim(n)-tim(1);
                dt=duration/(n-1);
                difft=diff(t);
                dtmin=min(difft);
                dtmax=max(difft);
            %
                outa = sprintf('\n start  = %8.4g sec  ',t(1));
                outb = sprintf('\n end    = %8.4g sec  ',t(n));
            
                out4 = sprintf('\n dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf('\n dt     = %8.4g sec  ',dt);
                out6 = sprintf('\n dtmax  = %8.4g sec  ',dtmax);
            
            %
                out7 = sprintf('\n srmin  = %8.4g samples/sec  ',1/dtmax);
                out8 = sprintf('\n sr     = %8.4g samples/sec  ',1/dt);
                out9 = sprintf('\n srmax  = %8.4g samples/sec  ',1/dtmin);
            
            string1=sprintf(' %d samples ',n);
            
            string_tss=sprintf('\n\n Time Limits ');
            
            big_string=strcat(string1,string_tss);
            big_string=strcat(big_string,outa);
            big_string=strcat(big_string,outb);
            
            string_ts=sprintf('\n\n Time Step ');
            
            big_string=strcat(big_string,string_ts);
            big_string=strcat(big_string,out4);
            big_string=strcat(big_string,out5);
            big_string=strcat(big_string,out6);
            
            string_sr=sprintf('\n\n Sample Rate ');
            big_string=strcat(big_string,string_sr);
            big_string=strcat(big_string,out7);
            big_string=strcat(big_string,out8);
            big_string=strcat(big_string,out9);
            
            disp(big_string);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Calculating skewness & kurtosis ');
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Calculating Zero & Peak crossings ');
            
            [pszcr,peak_rate,tpa,pa]=zero_crossing_function_alt(tim,amp,duration);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            string_amp=sprintf('\n\n Amplitude Stats ');
            string2=sprintf('\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=strcat(big_string,string_amp);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
                        
                mx=max(amp);
                mi=min(amp);
            
                amax=abs(mx);
                amin=abs(mi);
                if(amax<amin)
                    amax=amin;
                end
                crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);
            
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            
            [rf]=Rice_frequency(amp,dt);
            
            %
                string_rf=sprintf('\n\n Rice Characteristic Frequency = %8.4g Hz ',rf);
            
                string_zc=sprintf('\n Positive Slope Zero Cross Rate = %8.4g Hz ',pszcr);
                string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            disp(string_rf);
            disp(string_zc);
            disp(string_pr);
            
            big_string=strcat(big_string,string_rf);
            big_string=strcat(big_string,string_zc);
            big_string=strcat(big_string,string_pr);
            
            app.OutputStatisticsTextArea.Value=big_string;
            app.OutputStatisticsTextArea.Visible='on';
            app.OutputStatisticsTextAreaLabel.Visible='on';   
            
            fig_num=1;
            
            try
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),app.THM(:,2));
                title('Time History');
                xlabel(' Time(sec) ')
                ylabel(app.YS)
                grid on;
            catch
                warndlg('Plot time history failed');
                return;
            end

            try
                figure(fig_num);
                fig_num=fig_num+1;
                nbars=str2num(app.edit_bars.Value);
                xx=max(abs(app.THM(:,2)));
                x=linspace(-xx,xx,nbars);
                hh=histogram(app.THM(:,2),x);
                ylabel('Counts');
                title('Histogram');
                xlabel(app.YS);
                % qq=get(gca,'xlim');
            catch
                warndlg('ref 1: plot hist failed')
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            try
            
                x_label='Time (sec)';
                y_label=app.YS;
            
                nbars=str2num(app.edit_bars.Value);
            
                [fig_num]=plot_time_history_histogram(fig_num,app.THM,tstring,x_label,y_label,nbars);
            
            catch
                warndlg('Plot time history histogram failed')
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.PlotCumulativeHistogramCheckBox.Value)

                try
                    figure(fig_num);
                    fig_num=fig_num+1;
    
                    cdfplot(app.THM(:,2))
                    title(' Normalized Cumulative Histogram ');
                    ylabel(' Counts ');
                    xlabel(app.YS);
                    grid on;
                catch
                    warndlg('Plot cumulative histogram failed');
                end

            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.PlotAbsolutePeakHistogramCheckBox.Value==1)
                try
                    figure(fig_num);
                    fig_num=fig_num+1;
                    histogram(pa,nbars)
                    ylabel(' Counts');
                    xlabel(app.YS);
                    title('Absolute Peak Value Distribution');             
                catch
                    warndlg('plot hist failed');
                    return;
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.PlotTimeHistoryEnvelopeCheckBox.Value==1)
            
                try            
                    [envp,envn]=time_history_envelope(app.THM(:,1),app.THM(:,2),dt,pszcr);
                
                    figure(fig_num);
                    fig_num=fig_num+1;
                    plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),envp,app.THM(:,1),envn);
                    legend('Time History','Max Envelope','Min Envelope');
                    title(tstring);
                    xlabel('Time (sec)');
                    ylabel(app.YS);
                    grid on;
                
                catch
                    warndlg('Plot time history envelope failed');
                end

            end

        end    

        function Window(app)

            Items = char(app.SelectWindowListBox.Items);
            im=app.SelectWindowListBox.Value;
            [LL]=find_index(Items,im);            

            if(LL<=2)
                app.EnterSigmaEditField.Visible='on';
                app.EnterSigmaEditFieldLabel.Visible='on';
            else
                app.EnterSigmaEditField.Visible='off';
                app.EnterSigmaEditFieldLabel.Visible='off';         
            end    
            
            if(LL==1)
                app.EnterSigmaEditFieldLabel.Text='Enter Sigma'; 
            else
                app.EnterSigmaEditFieldLabel.Text='Enter T';   
            end

        end    

        function PadChange(app)
            Items = char(app.SelectPaddingMethodListBox.Items);
            im=app.SelectPaddingMethodListBox.Value;
            [LM]=find_index(Items,im);

            if(LM==1)
                ss=sprintf('Enter Total Duration (sec)');
            else
                ss=sprintf('Enter Total Number of Points');
            end
            app.EnterTotalDurationsecEditFieldLabel.Text=ss;

        end    

        function CurrentSampleRate(app)
            preprocess(app);
        end    

        function FilterMessage(app)
            try
                csr=str2num(app.CurrentSampleRateHzEditField.Value);
                
                nfactor=str2num(app.DownsampleFactorListBox.Value);
                
                nsr=csr/nfactor;
    
                app.NewSampleRateHzEditField.Value=sprintf('%8.4g',nsr);
                
                ss=sprintf('Filter frequency should be < %9.4g Hz',0.8*nsr/2);
                app.FilterMessageLabel.Text=ss;
            catch
            end
        end    

        function FilterOption(app)
            Items = char(app.ApplyFilterPriortoDownsamplingListBox.Items);
            im=app.ApplyFilterPriortoDownsamplingListBox.Value;
            [n]=find_index(Items,im);

            app.LowPassFreqHzEditField.Visible='off';
            app.LowPassFreqHzEditFieldLabel.Visible='off';
            
            app.HighFreqHzEditField.Visible='off';
            app.HighFreqHzEditFieldLabel.Visible='off';   

            if(n<=2)
                  app.FilteroptionhasrefilteringforphasecorrectionLabel.Visible='on';
            else
                  app.FilteroptionhasrefilteringforphasecorrectionLabel.Visible='off';
            end

            if(n==1)
                app.LowPassFreqHzEditField.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Text='Low Pass Freq (Hz)';
            end
            if(n==2)
                app.LowPassFreqHzEditField.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Visible='on';
                app.LowPassFreqHzEditFieldLabel.Text='Low Freq (Hz)';
                app.HighFreqHzEditField.Visible='on';
                app.HighFreqHzEditFieldLabel.Visible='on';              
            end
   
        end    

        function ScaleFactor(app)
            Items = char(app.MultiplyAmplitudebyScaleFactorListBox.Items);
            im=app.MultiplyAmplitudebyScaleFactorListBox.Value;
            [app.n_amplitude_scale]=find_index(Items,im);

            if(app.n_amplitude_scale==1)
                app.AmplitudeScaleFactorEditField.Visible='off';
                app.AmplitudeScaleFactorLabel.Visible='off';           
            else
                app.AmplitudeScaleFactorEditField.Visible='on';
                app.AmplitudeScaleFactorLabel.Visible='on';              
            end
        end    

        function AnalysisChange(app)
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                app.OutputSignalNameEditField.Visible='off';
                app.OutputSignalNameEditFieldLabel.Visible='off';
            else     
                app.OutputSignalNameEditField.Visible='on';
                app.OutputSignalNameEditFieldLabel.Visible='on';           
            end    

            app.TabGroup.SelectedTab=app.TabGroup.Children(n);

            CurrentSampleRate(app);        
 
        end    
        
        function ClosePlots(app)

            try
                delete(app.h);
            catch
            end

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            app.OutputStatisticsTextArea.Visible='off';     
            app.OutputStatisticsTextAreaLabel.Visible='off';             
        end
        
        function update(app)

            [cell_array]=update_time_history_edit();
         
            app.kflag=0;  

            try
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.SelectInputArrayListBox.Items);
                app.SelectInputArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.listbox_method.Value=app.listbox_method.Items{1};

        end

        function preprocess(app)
            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [m]=find_index(Items,im);           
            app.listbox_choice=m;
            
           
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            AI = char(app.SelectInputArrayListBox.Items);
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     try
                        app.SelectInputArrayListBox.Value=AI(1,:); 
                        FS=app.SelectInputArrayListBox.Value;
                        app.THM=evalin('base',FS);
                     catch    
                        msgbox('Select Input Array')
                        return;
                     end   
                 end
            end     

            sz=size(app.THM)

            if(sz(1)<=1 || sz(2)==0 )
                warndlg('Read Data Failed');
                return;
            end

            if(app.ShiftTimeforStartatT0CheckBox.Value)
                app.THM(:,1)=app.THM(:,1)-app.THM(1,1);
            end    

            try
                t=app.THM(:,1);
                y=app.THM(:,2);
            
                n=length(y);
                dt=(t(n)-t(1))/(n-1);
            
                csr=1/dt;
                css=sprintf('%9.4g',csr);
                css2=sprintf('%9.4g',2*csr);              

                app.CurrentSampleRateHzEditField.Value=css;
                app.CurrentSampleRateHzEditField_2.Value=css;            
                app.CurrentSampleRateHzEditField_3.Value=css; 
                app.CurrentSampleRateHzEditField_4.Value=css;
                app.NewSampleRateHzEditField_4.Value=css2;
                FilterMessage(app);              
            catch
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            ClosePlots(app);
            app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items{1};
            AnalysisChange(app);
            ScaleFactor(app);
            FilterOption(app);
            Window(app)

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            try
                closereq(app);
            catch
            end
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)

               try
                   [filename, pathname] = uigetfile('*.*');
               catch
                   disp('ref 8');
               end

               try
                   FS=filename;
                   filename = fullfile(pathname, filename)
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            disp('ref 1')
            CurrentSampleRate(app);

            m=app.TabGroup.SelectedTab;
            
            n=1;

            if(strcmp(m.Title,'a'))
                n=2;
            end              
            if(strcmp(m.Title,'b'))
                n=3;
            end     
            if(strcmp(m.Title,'c'))
                n=4;
            end              
            if(strcmp(m.Title,'d'))
                n=5;
            end 
            if(strcmp(m.Title,'e'))
                n=6;
            end              
            if(strcmp(m.Title,'f'))
                n=7;
            end     
            if(strcmp(m.Title,'g'))
                n=8;
            end              
            if(strcmp(m.Title,'h'))
                n=9;
            end   
            if(strcmp(m.Title,'i'))
                n=10;
            end      
            if(strcmp(m.Title,'j'))
                n=11;
            end              
            if(strcmp(m.Title,'k'))
                n=12;
            end  
            if(strcmp(m.Title,'l'))
                n=13;
            end           
            if(strcmp(m.Title,'m'))
                n=14;
            end    
            if(strcmp(m.Title,'n'))
                n=15;
            end               

            % if(n==1)
            %    app.OutputSignalNameEditField.Visible='off';
            %    app.OutputSignalNameEditFieldLabel.Visible='off';
            % else     
                app.OutputSignalNameEditField.Visible='on';
                app.OutputSignalNameEditFieldLabel.Visible='on';           
            % end    

            app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
            app.listbox_choice=n;
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
             AnalysisChange(app);
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
             AnalysisChange(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            try
                delete(app.h);
            catch
            end

            PlotInputButtonPushed(app, event);

            preprocess(app);
            ppp=1;

            app.YS=app.InputYaxisLabelEditField.Value;

            sz=size(app.THM);

            if(sz(2)==1)
                y=app.THM(:,1);
            else    
                t=app.THM(:,1);
                y=app.THM(:,2);
                to=t;
                yo=y;
            end

            fprintf('\n app.listbox_choice=%d\n',app.listbox_choice);

            if(app.listbox_choice<=2) % Statistics
                stats(app);
            end
            if(app.listbox_choice==3) % Time-varying Statistics
                tvfa(app);
            end
            if(app.listbox_choice==4) % Trend Removal & Amplitude Scaling
                if(app.n_amplitude_scale==2)
                    scale=str2num(app.AmplitudeScaleFactorEditField.Value);
                    y=scale*y;
                end

                Items = char(app.SelectTrendRemovalListBox.Items);
                im=app.SelectTrendRemovalListBox.Value;
                [n]=find_index(Items,im);

                if(n==1) % Mean
                    y=y-mean(y);
                end    
                if(n==2) % First-order
                    y=detrend(y);
                end    
                if(n==3) % Second-order
                    p = polyfit(t,y,2);
                    y= y-(p(1)*t.*t + p(2)*t + p(3));               
                end         

            end 
            if(app.listbox_choice==5) % Extract Segment
                ts=str2num(app.StartEditField.Value);
                te=str2num(app.EndEditField.Value);
                [t,y,dt,n]=extract_function(app.THM,ts,te);
            end     
            if(app.listbox_choice==6) % Decimate, Downsample
                FilterMessage(app);
                
                Items = char(app.DownsampleFactorListBox.Items);
                im=app.DownsampleFactorListBox.Value;
                [n]=find_index(Items,im);
                nfactor=n+1;

                Items = char(app.ApplyFilterPriortoDownsamplingListBox.Items);
                im=app.ApplyFilterPriortoDownsamplingListBox.Value;
                [nfilter]=find_index(Items,im);
                
                if(nfilter<=2)
                    app.FilteroptionhasrefilteringforphasecorrectionLabel.Visible='on';

                    iphase=1;
                    csr=str2num(app.CurrentSampleRateHzEditField.Value);
                    dt=1/csr;
                    
                    value_low = str2num(app.LowPassFreqHzEditField.Value);
                    
                    if isempty(value_low)
                        msgbox('Enter lowpass frequency')
                        return;
                    end   

                else
                     app.FilteroptionhasrefilteringforphasecorrectionLabel.Visible='off';      
                end

                if(nfilter==1)
                    
                    fc=value_low;
                    
                %    iband:   1=lowpass  2=highpass  3=bandpass 
                %
                %    iphase=1  for refiltering for phase correction
                %          =2  for no refiltering    
                
                    iband=1;
                
                    fl=fc;
                    fh=fc;
                    
                    [y,mu,sd,rms]=Butterworth_filter_function_alt(y,dt,iband,fl,fh,iphase);    
                   
                end
                if(nfilter==2)
                    
                    iband=3;
                    
                    value_high = str2num(app.HighFreqHzEditField.Value);
                    
                    if isempty(value_high)
                        msgbox('Enter high frequency')
                        return;
                    end     
                    
                    fh=value_low;
                    fl=value_high;
                    
                    [y,mu,sd,rms]=Butterworth_filter_function_alt(y,dt,iband,fl,fh,iphase);  
                    
                end    
                
                tn=t(1:nfactor:end);
                yn=y(1:nfactor:end);
                
                t = fix_size(tn);
                y = fix_size(yn);
                
            end 
            if(app.listbox_choice==7) % Cubic Spline Curve-fit
                
                n = length(t);
                
                sr=str2num(app.NewSampleRateHzEditField_2.Value);
                
                dt=1/sr;
                %
                np=round((t(n)-t(1))/dt);
                np=np+1;
                %
                xx=linspace(t(1),t(n),np); 
                %
                %
                yy = spline(t,y,xx);
                
                t=fix_size(xx);
                y=fix_size(yy);

                figure(3)
                plot(to,yo,t,y);
                title('Time Histories');
                xlabel(' Time(sec) ')
                legend('Input','Output');
                ylabel(app.YS);
                grid on;  

                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);

            end   
            if(app.listbox_choice==8) % Add Time Column
                sr=str2num(app.SampleRateHzEditField.Value);
                dt=1/sr;
                sz=size(app.THM);
                t=((1:sz(1))-1)*dt;
                t=fix_size(t);
            end 
            if(app.listbox_choice==9) % Add Trailing Zeros

                Items = char(app.SelectPaddingMethodListBox.Items);
                im=app.SelectPaddingMethodListBox.Value;
                [LM]=find_index(Items,im);

                sz=size(app.THM);
                n=sz(1);

                dt=mean(diff(app.THM(:,1)));
                dur=app.THM(end,1)-app.THM(1,1);
            
                num=str2num(app.EnterTotalDurationsecEditField.Value);
                
                if(LM==1)  %  Total Duration
                    
                    if(num<=dur)
                       warndlg('Total duration must be > input duration'); 
                       return; 
                    end
                    
                    m=round(num/dt);
                        
                else       %  Total Points
                    
                    if(num<=n)
                       warndlg('Total points must be > input points'); 
                       return; 
                    end
                    
                    m=num;
                
                end
                
                tt=zeros(m,1);
                tt(1:n)=t(1:n);
                    
                b=zeros(m,1);
                b(1:n)=b(1:n)+y(1:n);
                    
                ijk=1;
                    
                for i=(n+1):m
                    tt(i)=(ijk*dt)+t(n);
                    ijk=ijk+1;
                end

                t=fix_size(tt);
                y=fix_size(b);
                
            end     
            if(app.listbox_choice==10) % Linear Interpolation
                sr=str2num(app.NewSampleRateHzEditField_3.Value);

                dt=1/sr;
                
                tstart=app.THM(1,1);
                tend=app.THM(end,1);
                
                n=floor((tend-tstart)/dt);
                %
                upper=(n*dt)+tstart;
                
                
                fprintf('\n sr=%8.4g  dt=%8.4g  n=%d  tstart=%8.4g  upper=%8.4g \n',sr,dt,n,tstart,upper);
                
                %   This method is more reliable than Matlab's interp1
                
                [t,y]=linear_interpolation(t,y,dt); 
 
            end 
            if(app.listbox_choice==11) % Apply Window
                Items = char(app.SelectWindowListBox.Items);
                im=app.SelectWindowListBox.Value;
                [LL]=find_index(Items,im);
                
                t=app.THM(:,1);
                a=app.THM(:,2);
                
                dur=t(end)-t(1);
                
                if(LL==1)
                    sigma=str2num(app.EnterSigmaEditField.Value);
                    [b]=exponential_window_function(a,dur,sigma); 
                    t_string2=sprintf('Signal with Exponenital Window  sigma = %g',sigma);    
                end    
                if(LL==2)
                    T=str2num(app.EnterSigmaEditField.Value);
                    sigma=6.9087/T;
                    [b]=exponential_window_function(a,dur,sigma); 
                    t_string2=sprintf('Signal with Exponenital Window  sigma = %6.4g',sigma);    
                end 
                if(LL==3)
                    [b]=Hanning_function(a);   
                    t_string2='Signal with Compensated Hanning Window';      
                end    
                if(LL==4)
                    [b]=flat_top_function(a);   
                    t_string2='Signal with Compensated Flat Top Window';      
                end    

                y=fix_size(b);

                ylabel1=app.YS;
                ylabel2=app.YS;
                
                t_string1='Input Signal';
                               
                fig_num=3;
                
                xlabel2='Time (sec)';
                data1=[t a];
                data2=[t y];
                
                [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
               
                ppp=0;

            end    
            if(app.listbox_choice==12) % Peak Clip
                L=str2num(app.MinEditField.Value);
                U=str2num(app.MaxEditField.Value);

                sz=size(app.THM);
                
                THM_clip=app.THM;
                            
                for i=1:sz(1)
                    if(THM_clip(i,2)>U)
                        THM_clip(i,2)=U;
                    end    
                    if(THM_clip(i,2)<L)
                        THM_clip(i,2)=L;
                    end         
                end
                
                xlabel2='Time (sec)';
                
                ylab=app.YS;

                data1=app.THM;
                data2=THM_clip;
                
                t_string1='Input Data';
                t_string2='Clipped Data';
                
                fig_num=3;
                [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylab,ylab,data1,data2,t_string1,t_string2);

                y=THM_clip(:,2);

            end 
            if(app.listbox_choice==13) % Clipped Data Cubic Spline Fit
                ppp=0;

                L=str2num(app.MinEditField_2.Value);
                U=str2num(app.MaxEditField_2.Value);

                sz=size(app.THM);
                
                THM_clip=app.THM;
                
                n=sz(1);
                
                for i=n:-1:1
                    if(THM_clip(i,2)>U || THM_clip(i,2)<L )
                        THM_clip(i,:)=[];
                    end        
                end
                
                %%%
                
                x=THM_clip(:,1);
                y=THM_clip(:,2);
                
                n=length(x);
                
                sr=str2num(app.NewSampleRateHzEditField_4.Value);
                
                dt=1/sr;
                %
                np=round((x(end)-x(1))/dt);
                np=np+1;
                %
                xx=linspace(x(1),x(end),np); 
                %
                %
                yy = spline(x,y,xx);
                
                t=fix_size(xx);
                y=fix_size(yy);
                
                xlabel2='Time (sec)';
                
                ylab=app.YS;

                data1=app.THM;
                data2=[t y];

                max(data1(:,2))
                max(data2(:,2))
                
                t_string1='Input Data';
                t_string2='Cubic Spline Curve-fit';
                
                fig_num=3;
                [fig_num]=subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylab,ylab,data1,data2,t_string1,t_string2);
                
                figure(4)
                plot(data1(:,1),data1(:,2),t,y);
                title('Time Histories');
                xlabel(' Time(sec) ')
                legend('Input','Cubic Spline Curve-fit');
                ylabel(app.YS);
                grid on;  

            end   
            if(app.listbox_choice==14) % Interpolate via FFT

                data1=app.THM;  

                dt=mean(diff(data1(:,1)));
               
                N=str2num(app.SampleRateMultiplierListBox.Value);

                N=N*length(data1(:,2));

                t=(1:N)*dt-dt;

                t=fix_size(t);
                
                y = interpft(data1(:,2),N);

                y=y(1:length(t));

                y=fix_size(y);

            end

            if(ppp==1 && app.listbox_choice>1)
                figure(2)
                plot(t,y);
                title('Output Time History');
                xlabel(' Time(sec) ')
                ylabel(app.YS);
                grid on;  
                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);

                if(app.listbox_choice==5)
                    fig_num=3;
                    ylabel1=app.YS;
                    ylabel2=app.YS;
                    xlabel2='Time (sec)';
                    data1=app.THM;
                    data2=[t y];

                    if(contains(app.YS,'(G)'))
                        t_string1=sprintf('Input  %7.3g GRMS',std(app.THM(:,2)));
                        t_string2=sprintf('Output  %7.3g GRMS',std(y));
                    else
                        t_string1=sprintf('Input  %7.3g RMS',std(app.THM(:,2)));
                        t_string2=sprintf('Output  %7.3g RMS',std(y));
                    end

                    [fig_num]=...
                        subplots_two_linlin_two_titles_scale_same(fig_num,...
                        xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
                end

            end  

            if(app.listbox_choice>2)

                name=app.OutputSignalNameEditField.Value;
                assignin('base',name, [t y]); 
    
                disp(' ');
                disp(' Calculating skewness & kurtosis ');
                
                tim=t;
                amp=y;
                duration=tim(end)-tim(1);
                dt=mean(diff(tim));
                [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                disp(' Calculating Zero & Peak crossings ');
                
                [pszcr,peak_rate,tpa,pa]=zero_crossing_function_alt(tim,amp,duration);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
               
                string2=sprintf('\n      mean = %8.4g ',mu);
                string3=sprintf('\n   std dev = %8.4g ',sd);
                string4=sprintf('\n       RMS = %8.4g ',rms);
                string5=sprintf('\n\n  skewness = %8.4g ',sk);
                string6=sprintf('\n  kurtosis = %8.4g ',kt);
                
                big_string=string2;
                big_string=strcat(big_string,string3);
                big_string=strcat(big_string,string4);
                big_string=strcat(big_string,string5);
                big_string=strcat(big_string,string6);
                
                
                    mx=max(amp);
                    mi=min(amp);
                
                    amax=abs(mx);
                    amin=abs(mi);
                    if(amax<amin)
                        amax=amin;
                    end
                    crest=amax/rms;
                
                string7=sprintf('\n\n      Maximum = %8.4g ',mx);
                string8=sprintf('\n      Minimum = %8.4g ',mi);
                string9=sprintf('\n Crest Factor = %8.4g ',crest);
                
                
                big_string=strcat(big_string,string7);
                big_string=strcat(big_string,string8);
                big_string=strcat(big_string,string9);
                
                [rf]=Rice_frequency(amp,dt);
                
                %
                    string_rf=sprintf('\n\n Rice Characteristic Frequency = \n  %8.4g Hz \n',rf);
                
                    string_zc=sprintf('\n Positive Slope Zero Cross Rate = \n  %8.4g Hz \n',pszcr);
                    string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
                
                disp(string_rf);
                disp(string_zc);
                disp(string_pr);
                
                big_string=sprintf('%s %s',big_string,string_rf);
                big_string=sprintf('%s %s',big_string,string_zc);
                big_string=sprintf('%s %s',big_string,string_pr);
                
                app.OutputStatisticsTextArea.Value=big_string;
                app.OutputStatisticsTextArea.Visible='on';
                app.OutputStatisticsTextAreaLabel.Visible='on';  
    
                out1=sprintf('Save Complete.  Output array:  %s',name); 
                app.h=msgbox(out1);           
            end

        end

        % Button pushed function: PlotInputButton
        function PlotInputButtonPushed(app, event)
            
            preprocess(app);

            sz=size(app.THM);

            try
                figure(1)
                
                if(sz(2)==1)
                    plot(app.THM(:,1))
                else    
                    plot(app.THM(:,1),app.THM(:,2));
                    xlabel('Time (sec)');
                end

                title('Input Time History')
                grid on;
                YL=app.InputYaxisLabelEditField.Value;
                ylabel(YL);
                yy = ylim;

                ym=max(abs(yy));
                ylim([-ym,ym]);
            catch
                warndlg('Input Plot Failed');
                return;
            end
        end

        % Value changed function: MultiplyAmplitudebyScaleFactorListBox
        function MultiplyAmplitudebyScaleFactorListBoxValueChanged(app, event)
            ScaleFactor(app);
            ClosePlots(app);
        end

        % Value changed function: ApplyFilterPriortoDownsamplingListBox
        function ApplyFilterPriortoDownsamplingListBoxValueChanged(app, event)
            FilterOption(app);
            ClosePlots(app);
        end

        % Value changed function: DownsampleFactorListBox
        function DownsampleFactorListBoxValueChanged(app, event)
            FilterMessage(app);
            ClosePlots(app);
        end

        % Value changed function: SelectPaddingMethodListBox
        function SelectPaddingMethodListBoxValueChanged(app, event)
            PadChange(app);
            ClosePlots(app);   
        end

        % Callback function: AmplitudeScaleFactorEditField, eTab_3, fTab_3
        function AmplitudeScaleFactorEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectTrendRemovalListBox
        function SelectTrendRemovalListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: StartEditField
        function StartEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: EndEditField
        function EndEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: LowPassFreqHzEditField
        function LowPassFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: HighFreqHzEditField
        function HighFreqHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_2
        function NewSampleRateHzEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: EnterTotalDurationsecEditField
        function EnterTotalDurationsecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_3
        function NewSampleRateHzEditField_3ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectWindowListBox
        function SelectWindowListBoxValueChanged(app, event)
            ClosePlots(app);
            Window(app);
        end

        % Value changing function: EnterSigmaEditField
        function EnterSigmaEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxEditField
        function MaxEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinEditField
        function MinEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: NewSampleRateHzEditField_4
        function NewSampleRateHzEditField_4ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MaxEditField_2
        function MaxEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: MinEditField_2
        function MinEditField_2ValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            preprocess(app);
        end

        % Button pushed function: ViewEquationsButton
        function ViewEquationsButtonPushed(app, event)
            A = imread('time_history_window.jpg'); 
            figure(999)
            imshow(A,'border','tight','InitialMagnification',100) 
        end

        % Value changed function: PercentOverlapListBox
        function PercentOverlapListBoxValueChanged(app, event)
            ClosePlots(app);
        end

        % Value changing function: SegmentDurationsecEditField
        function SegmentDurationsecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: StartTimesecEditField
        function StartTimesecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Value changing function: EndTimesecEditField
        function EndTimesecEditFieldValueChanging(app, event)
            ClosePlots(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
     
            Items = char(app.SelectFunctionListBox.Items);
            im=app.SelectFunctionListBox.Value;
            [n]=find_index(Items,im); 

            if(n==1)
                data=app.peak_tv;      
            end    
            if(n==2)
                data=app.RMS_tv;       
            end  
            if(n==3)
                data=app.stddev_tv;    
            end    
            if(n==4)
                data=app.mean_tv;      
            end 
            if(n==5)
                data=app.freq_tv;            
            end           
            if(n==6)
                data=app.kurtosis_tv;            
            end
            name=app.OutputArrayNameEditField.Value;
            
            try
                assignin('base',name, data);
                msgbox('Array saved');
            catch
            end    

        end

        % Button pushed function: ApplySampleRateButton
        function ApplySampleRateButtonPushed(app, event)
                sr=str2num(app.CorrectSampleRateHzEditField.Value);
                dt=1/sr;

                n=length(app.THM(:,1));
 
                t=((0:n-1)*dt)' + app.THM(:,1);

                fig_num=2;
                figure(fig_num);
                fig_num=fig_num+1;
                plot(t,app.THM(:,2));
                xlabel(' Time (sec)');
                ylabel(app.YS);
                grid on;
                title('Time History');
            
                name=app.OutputSignalNameEditField.Value;
                assignin('base',name, [t app.THM(:,2)]);
                ss=sprintf('Array save as %s',name);
                msgbox(ss);
        end

        % Button down function: cTab_3
        function cTab_3ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalUtilitiesUIFigure = uifigure('Visible', 'off');
            app.SignalUtilitiesUIFigure.Name = 'Signal Utilities';
            app.signal_editing_utilities_aver10byTomIrvineLabel = uilabel(app.SignalUtilitiesUIFigure);
            app.signal_editing_utilities_aver10byTomIrvineLabel.Text = 'signal_editing_utilities_a   ver 1.8   by Tom Irvine         The input array must have two columns:  time (sec) & amplitude';
            app.TabGroup = uitabgroup(app.SignalUtilitiesUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ReadInputDataTab = uitab(app.TabGroup);
            app.ReadInputDataTab.Title = 'Read Input Data';
            app.listbox_method = uilistbox(app.ReadInputDataTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ReadInputDataTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.SelectInputArrayListBoxLabel = uilabel(app.ReadInputDataTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ReadInputDataTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.SelectInputArrayListBox.Value = {};
            app.UpdateListButton = uibutton(app.ReadInputDataTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.ShiftTimeforStartatT0CheckBox = uicheckbox(app.ReadInputDataTab);
            app.ShiftTimeforStartatT0CheckBox.Text = 'Shift Time for Start at T=0';
            app.InputYaxisLabelEditFieldLabel = uilabel(app.ReadInputDataTab);
            app.InputYaxisLabelEditFieldLabel.Text = 'Input Y-axis Label';
            app.InputYaxisLabelEditField = uieditfield(app.ReadInputDataTab, 'text');
            app.InputYaxisLabelEditField.Value = 'Accel (G)';
            app.aTab = uitab(app.TabGroup);
            app.aTab.Title = 'a';
            app.edit_bars = uieditfield(app.aTab, 'text');
            app.edit_bars.Tag = 'edit_bars';
            app.edit_bars.Value = '31';
            app.text6 = uilabel(app.aTab);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Number of Histogram Bars';
            app.PlotAbsolutePeakHistogramCheckBox = uicheckbox(app.aTab);
            app.PlotAbsolutePeakHistogramCheckBox.Text = 'Plot Absolute Peak Histogram';
            app.PlotTimeHistoryEnvelopeCheckBox = uicheckbox(app.aTab);
            app.PlotTimeHistoryEnvelopeCheckBox.Text = 'Plot Time History Envelope';
            app.PlotCumulativeHistogramCheckBox = uicheckbox(app.aTab);
            app.PlotCumulativeHistogramCheckBox.Text = 'Plot Cumulative Histogram';
            app.bTab_3 = uitab(app.TabGroup);
            app.bTab_3.Title = 'b';
            app.TabGroup2 = uitabgroup(app.bTab_3);
            app.CalculateTab = uitab(app.TabGroup2);
            app.CalculateTab.Title = 'Calculate';
            app.StartTimesecLabel = uilabel(app.CalculateTab);
            app.StartTimesecLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.CalculateTab, 'text');
            app.StartTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @StartTimesecEditFieldValueChanging, true);
            app.EndTimesecLabel = uilabel(app.CalculateTab);
            app.EndTimesecLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.CalculateTab, 'text');
            app.EndTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @EndTimesecEditFieldValueChanging, true);
            app.SegmentDurationsecLabel = uilabel(app.CalculateTab);
            app.SegmentDurationsecLabel.Text = {'Segment Duration '; '(sec)'};
            app.SegmentDurationsecEditField = uieditfield(app.CalculateTab, 'text');
            app.SegmentDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @SegmentDurationsecEditFieldValueChanging, true);
            app.PercentOverlapListBoxLabel = uilabel(app.CalculateTab);
            app.PercentOverlapListBoxLabel.Text = 'Percent Overlap';
            app.PercentOverlapListBox = uilistbox(app.CalculateTab);
            app.PercentOverlapListBox.Items = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.PercentOverlapListBox.ValueChangedFcn = createCallbackFcn(app, @PercentOverlapListBoxValueChanged, true);
            app.PercentOverlapListBox.Value = '0';
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.SelectFunctionListBoxLabel = uilabel(app.ExportTab);
            app.SelectFunctionListBoxLabel.Text = 'Select Function';
            app.SelectFunctionListBox = uilistbox(app.ExportTab);
            app.SelectFunctionListBox.Items = {'Peak     ', 'RMS      ', 'Std Dev  ', 'Mean     ', 'Frequency', 'Kurtosis'};
            app.SelectFunctionListBox.Value = 'Peak     ';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTab, 'text');
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.cTab_3 = uitab(app.TabGroup);
            app.cTab_3.Title = 'c';
            app.cTab_3.ButtonDownFcn = createCallbackFcn(app, @cTab_3ButtonDown, true);
            app.TrendRemovalAmplitudeScalingLabel = uilabel(app.cTab_3);
            app.TrendRemovalAmplitudeScalingLabel.Text = 'Trend Removal & Amplitude Scaling';
            app.InputYaxisLabelEditField_2Label = uilabel(app.cTab_3);
            app.InputYaxisLabelEditField_2Label.Text = 'Output Y-axis Label';
            app.OutputYaxisLabelEditField = uieditfield(app.cTab_3, 'text');
            app.OutputYaxisLabelEditField.Value = 'Accel (G)';
            app.MultiplyAmplitudebyScaleFactorLabel = uilabel(app.cTab_3);
            app.MultiplyAmplitudebyScaleFactorLabel.Text = {'Multiply Amplitude by '; 'Scale Factor'};
            app.MultiplyAmplitudebyScaleFactorListBox = uilistbox(app.cTab_3);
            app.MultiplyAmplitudebyScaleFactorListBox.Items = {'no', 'yes'};
            app.MultiplyAmplitudebyScaleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @MultiplyAmplitudebyScaleFactorListBoxValueChanged, true);
            app.MultiplyAmplitudebyScaleFactorListBox.Value = 'no';
            app.SelectTrendRemovalListBoxLabel = uilabel(app.cTab_3);
            app.SelectTrendRemovalListBoxLabel.Text = 'Select Trend Removal';
            app.SelectTrendRemovalListBox = uilistbox(app.cTab_3);
            app.SelectTrendRemovalListBox.Items = {'Mean', 'First-order', 'Second-order', 'None'};
            app.SelectTrendRemovalListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTrendRemovalListBoxValueChanged, true);
            app.SelectTrendRemovalListBox.Value = 'Mean';
            app.AmplitudeScaleFactorLabel = uilabel(app.cTab_3);
            app.AmplitudeScaleFactorLabel.Text = 'Amplitude Scale Factor';
            app.AmplitudeScaleFactorEditField = uieditfield(app.cTab_3, 'text');
            app.AmplitudeScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @AmplitudeScaleFactorEditFieldValueChanging, true);
            app.AmplitudeScaleFactorEditField.Value = '1';
            app.dTab_3 = uitab(app.TabGroup);
            app.dTab_3.Title = 'd';
            app.ExtractSegmentLabel = uilabel(app.dTab_3);
            app.ExtractSegmentLabel.Text = 'Extract Segment';
            app.StartEditFieldLabel = uilabel(app.dTab_3);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.dTab_3, 'text');
            app.StartEditField.ValueChangingFcn = createCallbackFcn(app, @StartEditFieldValueChanging, true);
            app.EndEditFieldLabel = uilabel(app.dTab_3);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.dTab_3, 'text');
            app.EndEditField.ValueChangingFcn = createCallbackFcn(app, @EndEditFieldValueChanging, true);
            app.SegmentTimesecLabel = uilabel(app.dTab_3);
            app.SegmentTimesecLabel.Text = 'Segment Time (sec)';
            app.eTab_3 = uitab(app.TabGroup);
            app.eTab_3.SizeChangedFcn = createCallbackFcn(app, @AmplitudeScaleFactorEditFieldValueChanging, true);
            app.eTab_3.Title = 'e';
            app.DecimateDownsampleLabel = uilabel(app.eTab_3);
            app.DecimateDownsampleLabel.Text = 'Decimate, Downsample';
            app.CurrentSampleRateHzEditFieldLabel = uilabel(app.eTab_3);
            app.CurrentSampleRateHzEditFieldLabel.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField = uieditfield(app.eTab_3, 'text');
            app.CurrentSampleRateHzEditField.Editable = 'off';
            app.NewSampleRateHzEditFieldLabel = uilabel(app.eTab_3);
            app.NewSampleRateHzEditFieldLabel.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField = uieditfield(app.eTab_3, 'text');
            app.NewSampleRateHzEditField.Editable = 'off';
            app.ApplyFilterPriortoDownsamplingLabel = uilabel(app.eTab_3);
            app.ApplyFilterPriortoDownsamplingLabel.Text = {'Apply Filter Prior to '; 'Downsampling'};
            app.ApplyFilterPriortoDownsamplingListBox = uilistbox(app.eTab_3);
            app.ApplyFilterPriortoDownsamplingListBox.Items = {'Yes, Low Pass', 'Yes, Band Pass', 'No'};
            app.ApplyFilterPriortoDownsamplingListBox.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterPriortoDownsamplingListBoxValueChanged, true);
            app.ApplyFilterPriortoDownsamplingListBox.Value = 'Yes, Low Pass';
            app.LowPassFreqHzEditFieldLabel = uilabel(app.eTab_3);
            app.LowPassFreqHzEditFieldLabel.Text = 'Low Pass Freq (Hz)';
            app.LowPassFreqHzEditField = uieditfield(app.eTab_3, 'text');
            app.LowPassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowPassFreqHzEditFieldValueChanging, true);
            app.FilterMessageLabel = uilabel(app.eTab_3);
            app.FilterMessageLabel.Text = 'Filter Message';
            app.DownsampleFactorLabel = uilabel(app.eTab_3);
            app.DownsampleFactorLabel.Text = {'Downsample '; 'Factor'};
            app.DownsampleFactorListBox = uilistbox(app.eTab_3);
            app.DownsampleFactorListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.DownsampleFactorListBox.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorListBoxValueChanged, true);
            app.DownsampleFactorListBox.Value = '2';
            app.HighFreqHzEditFieldLabel = uilabel(app.eTab_3);
            app.HighFreqHzEditFieldLabel.Text = 'High Freq (Hz)';
            app.HighFreqHzEditField = uieditfield(app.eTab_3, 'text');
            app.HighFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @HighFreqHzEditFieldValueChanging, true);
            app.FilteroptionhasrefilteringforphasecorrectionLabel = uilabel(app.eTab_3);
            app.FilteroptionhasrefilteringforphasecorrectionLabel.Text = 'Filter option has refiltering for phase correction';
            app.fTab_3 = uitab(app.TabGroup);
            app.fTab_3.SizeChangedFcn = createCallbackFcn(app, @AmplitudeScaleFactorEditFieldValueChanging, true);
            app.fTab_3.Title = 'f';
            app.CubicSplineCurvefitLabel = uilabel(app.fTab_3);
            app.CubicSplineCurvefitLabel.Text = 'Cubic Spline Curve-fit';
            app.CurrentSampleRateHzEditField_2Label = uilabel(app.fTab_3);
            app.CurrentSampleRateHzEditField_2Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_2 = uieditfield(app.fTab_3, 'text');
            app.CurrentSampleRateHzEditField_2.Editable = 'off';
            app.NewSampleRateHzEditField_2Label = uilabel(app.fTab_3);
            app.NewSampleRateHzEditField_2Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_2 = uieditfield(app.fTab_3, 'text');
            app.NewSampleRateHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_2ValueChanging, true);
            app.gTab_3 = uitab(app.TabGroup);
            app.gTab_3.Title = 'g';
            app.AddTimeColumnLabel = uilabel(app.gTab_3);
            app.AddTimeColumnLabel.Text = 'Add Time Column';
            app.SampleRateHzEditFieldLabel = uilabel(app.gTab_3);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.gTab_3, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.hTab = uitab(app.TabGroup);
            app.hTab.Title = 'h';
            app.AddTrailingZerosLabel = uilabel(app.hTab);
            app.AddTrailingZerosLabel.Text = 'Add Trailing Zeros';
            app.SelectPaddingMethodListBoxLabel = uilabel(app.hTab);
            app.SelectPaddingMethodListBoxLabel.Text = 'Select Padding Method';
            app.SelectPaddingMethodListBox = uilistbox(app.hTab);
            app.SelectPaddingMethodListBox.Items = {'Total Duration', 'Total Points'};
            app.SelectPaddingMethodListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPaddingMethodListBoxValueChanged, true);
            app.SelectPaddingMethodListBox.Value = 'Total Duration';
            app.EnterTotalDurationsecEditFieldLabel = uilabel(app.hTab);
            app.EnterTotalDurationsecEditFieldLabel.Text = 'Enter Total Duration (sec)';
            app.EnterTotalDurationsecEditField = uieditfield(app.hTab, 'text');
            app.EnterTotalDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @EnterTotalDurationsecEditFieldValueChanging, true);
            app.iTab_3 = uitab(app.TabGroup);
            app.iTab_3.Title = 'i';
            app.LinearInterpolationLabel = uilabel(app.iTab_3);
            app.LinearInterpolationLabel.Text = 'Linear Interpolation';
            app.CurrentSampleRateHzEditField_3Label = uilabel(app.iTab_3);
            app.CurrentSampleRateHzEditField_3Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_3 = uieditfield(app.iTab_3, 'text');
            app.CurrentSampleRateHzEditField_3.Editable = 'off';
            app.NewSampleRateHzEditField_3Label = uilabel(app.iTab_3);
            app.NewSampleRateHzEditField_3Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_3 = uieditfield(app.iTab_3, 'text');
            app.NewSampleRateHzEditField_3.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_3ValueChanging, true);
            app.jTab_2 = uitab(app.TabGroup);
            app.jTab_2.Title = 'j';
            app.ApplyWindowLabel = uilabel(app.jTab_2);
            app.ApplyWindowLabel.Text = 'Apply Window';
            app.ViewEquationsButton = uibutton(app.jTab_2, 'push');
            app.ViewEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationsButtonPushed, true);
            app.ViewEquationsButton.Text = 'View Equations';
            app.SelectWindowListBoxLabel = uilabel(app.jTab_2);
            app.SelectWindowListBoxLabel.Text = 'Select Window';
            app.SelectWindowListBox = uilistbox(app.jTab_2);
            app.SelectWindowListBox.Items = {'Exponential Sigma', 'Exponenital T', 'Hanning', 'Flat Top'};
            app.SelectWindowListBox.ValueChangedFcn = createCallbackFcn(app, @SelectWindowListBoxValueChanged, true);
            app.SelectWindowListBox.Value = 'Hanning';
            app.EnterSigmaEditFieldLabel = uilabel(app.jTab_2);
            app.EnterSigmaEditFieldLabel.Text = 'Enter Sigma';
            app.EnterSigmaEditField = uieditfield(app.jTab_2, 'text');
            app.EnterSigmaEditField.ValueChangingFcn = createCallbackFcn(app, @EnterSigmaEditFieldValueChanging, true);
            app.EnterSigmaEditField.Value = '1';
            app.kTab_2 = uitab(app.TabGroup);
            app.kTab_2.Title = 'k';
            app.PeakClipLabel = uilabel(app.kTab_2);
            app.PeakClipLabel.Text = 'Peak Clip';
            app.AmplitudeLimitsLabel = uilabel(app.kTab_2);
            app.AmplitudeLimitsLabel.Text = 'Amplitude Limits';
            app.MaxEditFieldLabel = uilabel(app.kTab_2);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.kTab_2, 'text');
            app.MaxEditField.ValueChangingFcn = createCallbackFcn(app, @MaxEditFieldValueChanging, true);
            app.MinEditFieldLabel = uilabel(app.kTab_2);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.kTab_2, 'text');
            app.MinEditField.ValueChangingFcn = createCallbackFcn(app, @MinEditFieldValueChanging, true);
            app.lTab = uitab(app.TabGroup);
            app.lTab.Title = 'l';
            app.ClippedDataCubicSplineFitLabel = uilabel(app.lTab);
            app.ClippedDataCubicSplineFitLabel.Text = 'Clipped Data Cubic Spline Fit';
            app.CurrentSampleRateHzEditField_4Label = uilabel(app.lTab);
            app.CurrentSampleRateHzEditField_4Label.Text = 'Current Sample Rate (Hz)';
            app.CurrentSampleRateHzEditField_4 = uieditfield(app.lTab, 'text');
            app.CurrentSampleRateHzEditField_4.Editable = 'off';
            app.NewSampleRateHzEditField_4Label = uilabel(app.lTab);
            app.NewSampleRateHzEditField_4Label.Text = 'New Sample Rate (Hz)';
            app.NewSampleRateHzEditField_4 = uieditfield(app.lTab, 'text');
            app.NewSampleRateHzEditField_4.ValueChangingFcn = createCallbackFcn(app, @NewSampleRateHzEditField_4ValueChanging, true);
            app.AmplitudeLimitsLabel_2 = uilabel(app.lTab);
            app.AmplitudeLimitsLabel_2.Text = 'Amplitude Limits';
            app.MaxEditField_2Label = uilabel(app.lTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.lTab, 'text');
            app.MaxEditField_2.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_2ValueChanging, true);
            app.MinEditField_2Label = uilabel(app.lTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.lTab, 'text');
            app.MinEditField_2.ValueChangingFcn = createCallbackFcn(app, @MinEditField_2ValueChanging, true);
            app.mTab = uitab(app.TabGroup);
            app.mTab.Title = 'm';
            app.SampleRateMultiplierListBoxLabel = uilabel(app.mTab);
            app.SampleRateMultiplierListBoxLabel.Text = 'Sample Rate Multiplier';
            app.SampleRateMultiplierListBox = uilistbox(app.mTab);
            app.SampleRateMultiplierListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.SampleRateMultiplierListBox.Value = '10';
            app.nTab = uitab(app.TabGroup);
            app.nTab.Title = 'n';
            app.ApplySampleRateButton = uibutton(app.nTab, 'push');
            app.ApplySampleRateButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySampleRateButtonPushed, true);
            app.ApplySampleRateButton.Text = 'Apply Sample Rate';
            app.CorrectSampleRateHzEditFieldLabel = uilabel(app.nTab);
            app.CorrectSampleRateHzEditFieldLabel.Text = 'Correct Sample Rate (Hz)';
            app.CorrectSampleRateHzEditField = uieditfield(app.nTab, 'text');
            app.Label = uilabel(app.nTab);
            app.Label.Text = 'This script fixes the time column where additional significant digits are needed.';
            app.ReturnButton = uibutton(app.SignalUtilitiesUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SelectAnalysisListBoxLabel = uilabel(app.SignalUtilitiesUIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.SignalUtilitiesUIFigure);
            app.SelectAnalysisListBox.Items = {'Read Data', 'Statistics', 'Time Varying Statistics', 'Trend Removal & Amplitude Scaling', 'Extract Segment', 'Decimate, Downsample', 'Cubic Spline Curve-fit', 'Add Time Column', 'Add Trailing Zeros', 'Linear Interpolation', 'Apply Window', 'Peak Clip', 'Clipped Data Cubic Spline Fit', 'Interpolation FFT Method', 'Fix Time Column'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Statistics';
            app.PerformAnalysisButton = uibutton(app.SignalUtilitiesUIFigure, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.CalculateButton = uibutton(app.SignalUtilitiesUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputSignalNameEditFieldLabel = uilabel(app.SignalUtilitiesUIFigure);
            app.OutputSignalNameEditFieldLabel.Text = 'Output Signal Name';
            app.OutputSignalNameEditField = uieditfield(app.SignalUtilitiesUIFigure, 'text');
            app.OutputSignalNameEditField.Value = 'signal_out';
            app.PlotInputButton = uibutton(app.SignalUtilitiesUIFigure, 'push');
            app.PlotInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputButtonPushed, true);
            app.PlotInputButton.Text = 'Plot Input';
            app.OutputStatisticsTextAreaLabel = uilabel(app.SignalUtilitiesUIFigure);
            app.OutputStatisticsTextAreaLabel.Text = 'Output Statistics';
            app.OutputStatisticsTextArea = uitextarea(app.SignalUtilitiesUIFigure);
            app.OutputStatisticsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SignalUtilitiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_editing_utilities_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SignalUtilitiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SignalUtilitiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalUtilitiesUIFigure)
        end
    end
end