classdef signal_sine_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalculateFFTButton           matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        SineTab                      matlab.ui.container.Tab
        CalculateButton              matlab.ui.control.Button
        RectifyListBox               matlab.ui.control.ListBox
        RectifyListBoxLabel          matlab.ui.control.Label
        PhaseAngledegEditField       matlab.ui.control.EditField
        PhaseAngledegEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.EditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.EditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        MultipleSineTonesTab         matlab.ui.container.Tab
        CalculateButton_multi        matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        NumberofTonesListBox         matlab.ui.control.ListBox
        NumberofTonesLabel           matlab.ui.control.Label
        OutputNameEditField          matlab.ui.control.EditField
        OutputNameEditFieldLabel     matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsTextAreaLabel         matlab.ui.control.Label
        YaxisLabelEditField          matlab.ui.control.EditField
        YaxisLabelEditFieldLabel     matlab.ui.control.Label
        SampleRateHzEditField        matlab.ui.control.EditField
        SampleRateHzEditFieldLabel   matlab.ui.control.Label
        DurationsecEditField         matlab.ui.control.EditField
        DurationsecEditFieldLabel    matlab.ui.control.Label
        ReturnButton                 matlab.ui.control.Button
        signal_sine_aver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM   % Description
        iflag %
    end
    
    methods (Access = private)

        function LB_change(app)
            clear(app); 

            N=str2num(app.NumberofTonesListBox.Value);
           
            for i = 1:N
               for j=1:2
                  data_s{i,j} = '';     
               end  
               data_s{i,3} = '0';   
            end
 
            app.UITable.Data=data_s;          
        end

        function clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.OutputNameEditFieldLabel.Visible='off';
            app.OutputNameEditField.Visible='off';
            app.SaveButton.Visible='off';
            app.CalculateFFTButton.Visible='off'; 
        end
        function ClosePlots(app)
            for i=1:120
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end      

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            clear(app);  
            LB_change(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function CalculateButtonPushed(app, event)
                
            Items = char(app.RectifyListBox.Items);
            im=app.RectifyListBox.Value;
            [irec]=find_index(Items,im);
 

              amp=str2num(app.AmplitudeEditField.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField.Value);
            phase=str2num(app.PhaseAngledegEditField.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
               
            dt=1/sr;
            omega=2*pi*freq;
            phase=phase*pi/180.;
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*sin(omega*TT+phase);
            
            if(irec==2)
                a=abs(a);
            end
            
            t_string='Sine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';  
            app.CalculateFFTButton.Visible='on'; 

            app.iflag=1;

        end

        % Callback function
        function RectifyListBoxValueChanged(app, event)
            clear(app); 
        end

        % Callback function
        function AmplitudeEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function FrequencyHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function PhaseAngledegEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=app.OutputNameEditField.Value;
            assignin('base',name,app.THM);
        end

        % Button pushed function: CalculateButton_multi
        function CalculateButton_multiPushed(app, event)

            N=str2num(app.NumberofTonesListBox.Value);
            
            A=char(app.UITable.Data);
            
            B=str2num(A);
            
            peak=B(1:N);
            freq=B((N+1):(2*N)); 
            phase=B((2*N+1):(3*N)); 
            
            phase=phase*pi/180;
            
            tmax=str2num(app.DurationsecEditField.Value);
            sr=str2num(app.SampleRateHzEditField.Value); 
            ylab=app.YaxisLabelEditField.Value;

            dt=1/sr;
            if(max(freq)>(sr/20))
               sr=max(freq)*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               out1=sprintf('%8.4g',sr);
               set(handles.edit_sr,'String',out1);
            end    
       
            omega=2.*pi*freq;
        %    
            np=round(tmax/dt);
            TT=linspace(0,np*dt,(np+1)); 
            a = zeros(1,(np+1));  
        %    
            for i=1:N
                a=a+peak(i)*sin(omega(i)*TT-phase(i));
            end  
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            figure(1);
            plot(TT,a);
            if(i==2)
                tstring='Beat Frequency Effect';
            else
                tstring='Multiple Sine Tones';        
            end
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(ylab);
            
            app.THM=[TT a];
            app.iflag=2;
            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';   

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            CalculateButtonPushed(app, event)
        end

        % Value changed function: NumberofTonesListBox
        function NumberofTonesListBoxValueChanged(app, event)
            
            LB_change(app);
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            clear(app); 
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            vibrationdata_fourier_transform_ah;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.signal_sine_aver11byTomIrvineLabel = uilabel(app.UIFigure);
            app.signal_sine_aver11byTomIrvineLabel.Text = 'signal_sine_a   ver 1.1  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.DurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UIFigure, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.YaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNameEditField.Value = 'sine';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.AmplitudeEditFieldLabel = uilabel(app.SineTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineTab, 'text');
            app.FrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineTab, 'text');
            app.PhaseAngledegEditFieldLabel = uilabel(app.SineTab);
            app.PhaseAngledegEditFieldLabel.Text = 'Phase Angle (deg)';
            app.PhaseAngledegEditField = uieditfield(app.SineTab, 'text');
            app.PhaseAngledegEditField.Value = '0';
            app.RectifyListBoxLabel = uilabel(app.SineTab);
            app.RectifyListBoxLabel.Text = 'Rectify';
            app.RectifyListBox = uilistbox(app.SineTab);
            app.RectifyListBox.Items = {'No', 'Yes'};
            app.RectifyListBox.Value = 'No';
            app.CalculateButton = uibutton(app.SineTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            app.MultipleSineTonesTab = uitab(app.TabGroup);
            app.MultipleSineTonesTab.Title = 'Multiple Sine Tones';
            app.NumberofTonesLabel = uilabel(app.MultipleSineTonesTab);
            app.NumberofTonesLabel.Text = {'Number '; 'of Tones'};
            app.NumberofTonesListBox = uilistbox(app.MultipleSineTonesTab);
            app.NumberofTonesListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofTonesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofTonesListBoxValueChanged, true);
            app.NumberofTonesListBox.Value = '2';
            app.UITable = uitable(app.MultipleSineTonesTab);
            app.UITable.ColumnName = {'Amplitude'; 'Freq (Hz)'; 'Phase (deg)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CalculateButton_multi = uibutton(app.MultipleSineTonesTab, 'push');
            app.CalculateButton_multi.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_multiPushed, true);
            app.CalculateButton_multi.Text = 'Calculate';
            app.CalculateFFTButton = uibutton(app.UIFigure, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_sine_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end