classdef signal_sine_sweep_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISineSweepUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GenerateTab                    matlab.ui.container.Tab
        NumberofOctavesEditField       matlab.ui.control.NumericEditField
        NumberofOctavesEditFieldLabel  matlab.ui.control.Label
        CalculateSpectrumFunctionListBox  matlab.ui.control.ListBox
        CalculateSpectrumFunctionListBoxLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        NumberofCoordinatesListBox     matlab.ui.control.ListBox
        NumberofCoordinatesListBoxLabel  matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        TimeOptionListBox              matlab.ui.control.ListBox
        TimeOptionListBoxLabel         matlab.ui.control.Label
        DurationsecEditField           matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel      matlab.ui.control.Label
        SampleRateHzEditField          matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel     matlab.ui.control.Label
        SweepDirectionListBox          matlab.ui.control.ListBox
        SweepDirectionListBoxLabel     matlab.ui.control.Label
        SweepTypeListBox               matlab.ui.control.ListBox
        SweepTypeListBoxLabel          matlab.ui.control.Label
        GenerateButton                 matlab.ui.control.Button
        SDOFResponseTab                matlab.ui.container.Tab
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyHzEditField    matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        CalculateListBox               matlab.ui.control.ListBox
        CalculateListBoxLabel          matlab.ui.control.Label
        RelDispResponseArrayEditField  matlab.ui.control.EditField
        RelDispResponseArrayEditFieldLabel  matlab.ui.control.Label
        AccelResponseArrayEditField    matlab.ui.control.EditField
        AccelResponseArrayEditFieldLabel  matlab.ui.control.Label
        BaseExcitationArrayEditField   matlab.ui.control.EditField
        BaseExcitationArrayEditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ResultsTextArea_sweep          matlab.ui.control.TextArea
        ResultsTextAreaLabel_sweep     matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        signal_sine_sweep_aver11byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        num    %
        ntype  %
        signal %
        fff    %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end               
        end
        
        function ChangeTimeOption(app)
            n=app.SweepTypeListBox.Value;
            app.DurationsecEditFieldLabel.Text='Duration (sec)';
                
            if(strcmp(n,'linear'))
                app.TimeOptionListBox.Visible='off';
                app.TimeOptionListBoxLabel.Visible='off';
                app.ntype=1;
            else
                app.TimeOptionListBox.Visible='on';
                app.TimeOptionListBoxLabel.Visible='on';
                m=app.TimeOptionListBox.Value;
                if(contains(m,'Sweep'))
                    app.DurationsecEditFieldLabel.Text='Sweep Rate (oct/min)';    
                end
                app.ntype=2;
            end
        end
        
        function ChangeNumberCoordinates(app)
            ns=app.NumberofCoordinatesListBox.Value;
            app.num=str2num(ns); 
            
            q=zeros(app.num,2);
            
            app.UITable.Data=q;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeTimeOption(app);   
            ChangeNumberCoordinates(app);
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';    
            app.UITable.ColumnEditable = true(1,2);
            app.NumberofOctavesEditField.Enable='off';
            app.BaseExcitationArrayEditField.HorizontalAlignment='center';
            app.AccelResponseArrayEditField.HorizontalAlignment='center'; 
            app.RelDispResponseArrayEditField.HorizontalAlignment='center';              
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
           
            disp(' ') 
            disp(' * * * * * * ')
            disp(' ')
        
            ClosePlots(app);
            
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';

            N=app.num;
            
            ndirs=app.SweepDirectionListBox.Value;
            
            if(strcmp(ndirs,'up'))
                ndir=1;
            end
            if(strcmp(ndirs,'down'))
                ndir=2;
            end            
            if(strcmp(ndirs,'up & down'))
                ndir=3;
            end               
  
            tmax=app.DurationsecEditField.Value;
            
            B=app.UITable.Data;
            
            freq=B(1:N);
            amp=B((N+1):(2*N));
            
            freq=fix_size(freq);
            amp=fix_size(amp);
            
            fa=[freq amp];
            
            fa=sortrows(fa,1);
            
            app.fff=fa(:,1);
            aaa=fa(:,2);
            
            sr=app.SampleRateHzEditField.Value;
            dt=1/sr;
            
            if(max(app.fff)>(sr/10))
                   sr=max(app.fff)*10;
                   dt=1/sr;
                   msgbox('Note: sample rate increased ');
                   app.SampleRateHzEditField.Value=sr;
            end
           
            f1=app.fff(1);
            f2=app.fff(end);
            oct=log2(f2/f1);

            nto=app.TimeOptionListBox.Value;
            
            disp(' ');
            if(app.ntype==1)
                dur=tmax;
            else
                if(contains(nto,'Duration'))
                    dur=tmax;
                else    
                    dur=(oct/tmax)*60;
                    fprintf(' %7.3g octaves/min ',tmax); 
                end    
            end

            fprintf(' %6.3g octaves \n',oct);
            fprintf(' %7.3g seconds \n',dur);       
            
            app.NumberofOctavesEditField.Value=oct;
            
            ns=ceil(dur*sr);
            
            if(ndir==3)
                ns=ns*2;
            end
            
            ntimes = ns;
            
            tpi=2.*pi;
            
            if(app.ntype==1)  % linear
                rate=(f2-f1)/dur;
            else              % log
                rate=oct/dur;
            end
            
            maxn=90000000;
            
            if(ntimes>maxn)
                ntimes=maxn;
            %    
                out1=sprintf('Time history truncated to %d points,',maxn);
                out5 = sprintf('or %g sec',maxn*dt);
                sss=strcat(out1,out5);
            %  
                msgbox(sss,'Warning','warn');        
            end
            
            if(ndir==3)
                nt=ntimes/2;
            else
                nt=ntimes;     
            end
            
            TT=linspace(dt,(ntimes+1)*dt,ntimes); 
            
               a = zeros(1,ntimes);
             arg = zeros(1,nt);
            freq = zeros(1,nt);
            
            if(app.ntype==1)  % linear
            %
            % 0.5 factor is necessary to obtain correct number of cycles for linear case.
            %
            %	fspectral= (     rate*t ) + f1;
            %
                fmax=0.5*(f2-f1)+f1;
                freq=linspace(f1,fmax,nt);     
            %
            else  % log
            %  
            %   fspectral = f1*pow(2.,rate*t);
            %
                for i=1:nt
                    arg(i)=-1.+2^(rate*TT(i));
                end   
            end
            
            freq=fix_size(freq);
            TT=fix_size(TT);
            
            if(app.ntype==1) % linear
               arg=tpi*freq.*TT(1:nt);
            else             % log
               arg=tpi*f1*arg/(rate*log(2));  
            end
            
            if(ndir==3)
                   arg1=arg;
                   arg1=fix_size(arg1);
                   arg2=flipud(arg1);       
                   arg=[arg1; arg2];
            end 
            
            ntimes=length(a);
            
            if(app.ntype==1) % linear
                spectral=linspace(f1,f2,nt); 
            else 
                f1=log10(f1);
                f2=log10(f2);
                spectral=logspace(f1,f2,nt);         
            end
            
            limit=length(app.fff)-1;
            amplitude=zeros(1,nt);
            
            for i=1:nt
               for j=1:limit
                   if(app.fff(j)<=spectral(i) && app.fff(j+1)>=spectral(i))
                        x=spectral(i)-app.fff(j);
                        L=app.fff(j+1)-app.fff(j);
                        c2=x/L;
                        c1=1-x/L;
                        amplitude(i)=c1*aaa(j)+c2*aaa(j+1);
                        break;
                   end
               end
            end
            
            if(ndir==3)
                a(1:nt)=sin(arg(1:nt));  
                
                diff_rec=1.0e+90;
                 
                for i=0:359
                    theta=pi*i/180;
            
                    a1=a(nt-1);
                    a2=a(nt);
                    a3=sin(arg(nt+1)+theta);
                    a4=sin(arg(nt+2)+theta);        
            
                    ab=[a1 a2 a3 a4];
                    aa=detrend(ab);
                    
                    diff=norm(aa);
                    
                    if(diff<diff_rec)
                        diff_rec=diff;
                        psi=theta;
                    end
                    
                end
            %    psi
            
                a((nt+1):ntimes)=sin(arg((nt+1):ntimes)+psi);
            else
                a=sin(arg);
            end
            %
            
            if(ndir==3)
                   amp1=amplitude;
                   amp1=fix_size(amp1);
                   amp2=flipud(amp1);       
                   amplitude=[amp1; amp2];    
            end
            
            a=fix_size(a);
            amplitude=fix_size(amplitude);
            
            a(1)=0.;
            a=a.*amplitude;
            TT=TT-dt;
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            if(ndir==2)
                a=flipud(a);
            end
            
            figure(1);
            plot(TT,a);
            tstring='Sine Sweep';
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel('Amplitude');
            
            app.signal=[TT a];            
            
            fig_num=2;
            
            t=TT;    
            
            numt=length(t);
            
            %
            %   positive slope zero crossing times for input
            %
            
            tindex=zeros(numt,1);
            tz=zeros(numt,1);
            
            k=1;
            for i=2:numt
                if( a(i) > a(i-1) && a(i) > 0 && a(i-1) <=0)
                    tindex(k)=i;
                    L=a(i)-a(i-1);
                    c1=a(i)/L;
                    c2=1-c1;
                    tz(k)=c1*t(i-1)+c2*t(i);
                    k=k+1;
                end
            end
            
            nt=k-1;   % number of positive slope zero crossings
            
            %% step=round(str2num(get(handles.edit_step,'String')));   % with sliding window
            
            step=ceil((1/app.fff(1))/dt)/25;
            
            if(step<20)
                step=20;
            end
 
            
            m=1;
            for i=(step+1):nt
                m=m+1;
            end
            m=m-2;  % leave
             
            aa=zeros(m,1);
            f=zeros(m,1);
            
            nspec=app.CalculateSpectrumFunctionListBox.Value;
                
            if(strcmp(nspec,'Yes'))
            
                progressbar;
            
                m=1;
                
                for i=(step+1):nt
                
                    progressbar(i/nt);
                
                    kk=tindex(i);
                    jj=tindex(i-step);
                    T=(tz(i)-tz(i-step))/step;
                    f(m)=1/T;
                    aa(m)=max(abs(a(jj:kk)));
                
                    m=m+1;
                end
            
                pause(0.2);
                progressbar(1);
                
                ppp=[f aa];
                t_string='Sine Sweep Spectral Function';
                x_label='Frequency (Hz)';
                y_label='Accel (G)';
                
                fmin=f(1);
                fmax=f(end);
                
                md=5;
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                
            end
            
            name=app.OutputArrayNameEditField.Value;
            
            assignin('base',name, app.signal);   
            
            disp(' ');
            
            msgbox('Calculation Complete');  
 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            disp(' ');
            disp(' * * * * * * * ');
            disp(' ');
        
            ClosePlots(app);
            
            iu=1;
            
            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            Q=str2num(app.AmplificationFactorQEditField.Value);
            FS=app.BaseExcitationArrayEditField.Value;

            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end 
            
            damp=1/(2*Q);
            
            dt=mean(diff(THM(:,1)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);
            %
            %  SRS engine
            %
            
            a_pos=zeros(1,1);
            a_neg=zeros(1,1);
            rd_pos=zeros(1,1);
            rd_neg=zeros(1,1);
            
            yy=THM(:,2);
            
            for j=1:1
            %
                disp(' ')
                disp(' Calculating absolute acceleration');
            %
                [a_resp,a_pos(j),a_neg(j)]=arbit_engine(a1(j),a2(j),b1(j),b2(j),b3(j),yy);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ')
                disp(' Calculating relative displacement');
            %
                [rd_resp,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),yy);

                if(iu==1)
                    scale=386;
                end
                if(iu==2)
                    scale=9.81*1000;        
                end
                if(iu==3)
                    scale=1000;        
                end    
            %
                rd_resp=rd_resp*scale;
                rd_pos(j)=rd_pos(j)*scale;
                rd_neg(j)=rd_neg(j)*scale;

            end
            %
            if(iu==1 || iu==2)
                big_string=sprintf(' Acceleration Response (G) \n');
            else
                big_string=sprintf(' Acceleration Response (m/sec^2) \n');
            end
            
            string1=sprintf('\n\n max=%8.4g',a_pos(1));
            string2=sprintf('\n min=%8.4g',-a_neg(1));
    
            big_string=strcat(big_string,string1);
            big_string=strcat(big_string,string2);
                       
            if(iu==1)
                string8=sprintf('\n\n Relative Displacement (in) \n');    
            else
                string8=sprintf('\n\n Relative Displacement (mm) \n');    
            end
                
            string9=sprintf('\n\n max=%7.3g',rd_pos(1));
            string10=sprintf('\n min=%7.3g',rd_neg(1));

            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);            
            
            fprintf('\n Q=%g \n',Q);
            
            app.ResultsTextArea_sweep.Value=big_string;
            app.ResultsTextArea_sweep.Visible='on';
            app.ResultsTextAreaLabel_sweep.Visible='on';            
            
            disp(' ');
            disp(big_string);
              
            fig_num=1;            
  
            figure(fig_num)
            fig_num=fig_num+1;
            plot(THM(:,1),THM(:,2),THM(:,1),a_resp);
            xlabel(' Time(sec) ');
            legend('Base Input','Response');
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            grid on;
            title('Acceleration Time History');
            
            if(iu==1 || iu==2)
                    ylabel1='Accel (G)';
                    ylabel2='Accel (G)'; 
            else
                    ylabel1='Accel (m/sec^2)';
                    ylabel2='Accel (m/sec^2)';     
            end
            
            xlabel2='Time (sec)';
            
            t_string1=('Base Input');
            t_string2=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
            
            data1=THM;
            data2=[THM(:,1) a_resp];
            
            [fig_num]=...
                subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,...
                                                              data1,data2,t_string1,t_string2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            figure(fig_num);
            fig_num=fig_num+1;
            plot(THM(:,1),rd_resp);
            out1=sprintf('Relative Displacement fn=%g Hz  Q=%g',fn,Q);
            title(out1);
            xlabel(' Time(sec) ')
            if(iu==1)
                ylabel('Rel Disp (in)')
            else
                ylabel('Rel Disp (mm)')    
            end
            grid on;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            name_a=app.AccelResponseArrayEditField.Value;
            name_rd=app.RelDispResponseArrayEditField.Value;
            
            assignin('base',name_a, [THM(:,1) a_resp]);
            assignin('base',name_rd, [THM(:,1) rd_resp]);   
         
            iss=strtrim(app.CalculateListBox.Value);
            
            if(strcmp(iss,'Time Histories Only'))
                ijk=1;
            end
            if(strcmp(iss,'Transmissibility'))
                ijk=2;
            end
            if(strcmp(iss,'Transmissibility & Phase'))
                ijk=3;
            end
                        
            if(ijk>=2)
                a=yy;
                b=a_resp;
                
                nt=length(a);
                
                %
                %   positive slope zero crossing times for input
                %
                tindex=zeros(nt,1);
                tz=zeros(nt,1);
                
                t=THM(:,1);
                
                k=1;
                for i=2:nt
                    if( a(i) > a(i-1) && a(i) > 0 && a(i-1) <=0)
                        tindex(k)=i;
                        L=a(i)-a(i-1);
                        c1=a(i)/L;
                        c2=1-c1;
                        tz(k)=c1*t(i-1)+c2*t(i);
                        k=k+1;
                    end
                end
                
                nt=k-1;   % number of positive slope zero crossings
                
                step=ceil((1/app.fff(1))/dt)/25;
            
                if(step<20)
                    step=20;
                end
 
                m=1;
                for i=(step+1):nt
                    m=m+1;
                end
                m=m-2;  % leave
                 
                aa=zeros(m,1);
                bb=zeros(m,1);
                trans=zeros(m,1);
                f=zeros(m,1);
                phase=zeros(m,1);
                    
                progressbar;
                
                m=1;
                for i=(step+1):nt
                    
                    progressbar(i/nt);
                    
                    kk=tindex(i);
                    jj=tindex(i-step);
                    T=(tz(i)-tz(i-step))/step;
                    f(m)=1/T;
                    aa(m)=std(a(jj:kk));
                    bb(m)=std(b(jj:kk));
                    trans(m)=bb(m)/aa(m);
                    
                    if(ijk==3)
                        
                        tpi=2*pi;
                        
                        omega=tpi*f(m);
                    
                        [~,~,~,~,~,x3a,~]=sine_lsf_function(a(jj:kk),t(jj:kk),omega);
                        [~,~,~,~,~,x3b,~]=sine_lsf_function(b(jj:kk),t(jj:kk),omega);
                                                
                        phase(m)=x3a-x3b;
                        
                        if(phase(m)<0.)
                            phase(m)=phase(m)+tpi;
                        end
                    
                    end    
                    
                    m=m+1;
                end
                
                pause(0.2);
                progressbar(1);
                
                phase=(180/pi)*phase;
                
                figure(fig_num);
                fig_num=fig_num+1;
                
                plot(f,trans);
                title('Sine Sweep Transmissibility');
                xlabel('Frequency (Hz)');
                
                k=1;
                
                if( k>=1 )
                    ylabel('Trans (G/G)');
                else
                    ylabel('Trans');
                end    
                grid on;
                
                xl = xlim;
                fmin=xl(1);
                fmax=xl(2);
                
                trans=[f trans];
                
                if(ijk==3)
                    
                    t_string='Sine Sweep Magnitude & Phase';
                    
                    ff=f;
                    FRF_p=phase;
                    FRF_m=trans(:,2);
                    ylab='Accel (G)';
                    md=4;
                    
                    [fig_num]=...
                    plot_magnitude_phase_function_linear(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
                    
                end
                
                [fmax,amax]=find_max(trans);
                
                disp(' ');
                disp('* * * * * * * * * ');
                disp(' ');
                
                fprintf('\n  Maximum Transmissibility: (%8.4g Hz, %8.4g G/G) \n\n',amax,fmax);
                
                msgbox('Calculation complete');
                          
            end
            
        end

        % Value changed function: TimeOptionListBox
        function TimeOptionListBoxValueChanged(app, event)
            ChangeTimeOption(app);
        end

        % Value changed function: SweepTypeListBox
        function SweepTypeListBoxValueChanged(app, event)
            ChangeTimeOption(app);
        end

        % Value changed function: NumberofCoordinatesListBox
        function NumberofCoordinatesListBoxValueChanged(app, event)
            ChangeNumberCoordinates(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            qs=app.OutputArrayNameEditField.Value;
            app.BaseExcitationArrayEditField.Value=qs;
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';     
        end

        % Callback function
        function NaturalFrequencyHzEditFieldValueChanged(app, event)
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';     
        end

        % Callback function
        function AmplificationFactorQEditFieldValueChanged(app, event)
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';     
        end

        % Value changing function: BaseExcitationArrayEditField
        function BaseExcitationArrayEditFieldValueChanging(app, event)
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';     
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISineSweepUIFigure = uifigure('Visible', 'off');
            app.UISineSweepUIFigure.Name = 'UI Sine Sweep';
            app.ReturnButton = uibutton(app.UISineSweepUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.signal_sine_sweep_aver11byTomIrvineLabel = uilabel(app.UISineSweepUIFigure);
            app.signal_sine_sweep_aver11byTomIrvineLabel.Text = 'signal_sine_sweep_a   ver 1.1  by Tom Irvine';
            app.TabGroup = uitabgroup(app.UISineSweepUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GenerateTab = uitab(app.TabGroup);
            app.GenerateTab.Title = 'Generate';
            app.GenerateButton = uibutton(app.GenerateTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SweepTypeListBoxLabel = uilabel(app.GenerateTab);
            app.SweepTypeListBoxLabel.Text = 'Sweep Type';
            app.SweepTypeListBox = uilistbox(app.GenerateTab);
            app.SweepTypeListBox.Items = {'linear', 'log'};
            app.SweepTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SweepTypeListBoxValueChanged, true);
            app.SweepTypeListBox.Value = 'linear';
            app.SweepDirectionListBoxLabel = uilabel(app.GenerateTab);
            app.SweepDirectionListBoxLabel.Text = 'Sweep Direction';
            app.SweepDirectionListBox = uilistbox(app.GenerateTab);
            app.SweepDirectionListBox.Items = {'up', 'down', 'up & down'};
            app.SweepDirectionListBox.Value = 'up';
            app.SampleRateHzEditFieldLabel = uilabel(app.GenerateTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.GenerateTab, 'numeric');
            app.DurationsecEditFieldLabel = uilabel(app.GenerateTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.GenerateTab, 'numeric');
            app.TimeOptionListBoxLabel = uilabel(app.GenerateTab);
            app.TimeOptionListBoxLabel.Text = 'Time Option';
            app.TimeOptionListBox = uilistbox(app.GenerateTab);
            app.TimeOptionListBox.Items = {'Duration', 'Sweep Rate'};
            app.TimeOptionListBox.ValueChangedFcn = createCallbackFcn(app, @TimeOptionListBoxValueChanged, true);
            app.TimeOptionListBox.Value = 'Duration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.GenerateTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.GenerateTab, 'text');
            app.OutputArrayNameEditField.Value = 'sine_sweep';
            app.NumberofCoordinatesListBoxLabel = uilabel(app.GenerateTab);
            app.NumberofCoordinatesListBoxLabel.Text = 'Number of Coordinates';
            app.NumberofCoordinatesListBox = uilistbox(app.GenerateTab);
            app.NumberofCoordinatesListBox.Items = {'2', '3', '4', '5', '6'};
            app.NumberofCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCoordinatesListBoxValueChanged, true);
            app.NumberofCoordinatesListBox.Value = '2';
            app.UITable = uitable(app.GenerateTab);
            app.UITable.ColumnName = {'Freq (Hz)'; 'Accel (G)'};
            app.UITable.RowName = {};
            app.CalculateSpectrumFunctionListBoxLabel = uilabel(app.GenerateTab);
            app.CalculateSpectrumFunctionListBoxLabel.Text = 'Calculate Spectrum Function';
            app.CalculateSpectrumFunctionListBox = uilistbox(app.GenerateTab);
            app.CalculateSpectrumFunctionListBox.Items = {'Yes', 'No'};
            app.CalculateSpectrumFunctionListBox.Value = 'No';
            app.NumberofOctavesEditFieldLabel = uilabel(app.GenerateTab);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.GenerateTab, 'numeric');
            app.SDOFResponseTab = uitab(app.TabGroup);
            app.SDOFResponseTab.Title = 'SDOF Response';
            app.CalculateButton = uibutton(app.SDOFResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsTextAreaLabel_sweep = uilabel(app.SDOFResponseTab);
            app.ResultsTextAreaLabel_sweep.Text = 'Results';
            app.ResultsTextArea_sweep = uitextarea(app.SDOFResponseTab);
            app.Image = uiimage(app.SDOFResponseTab);
            app.Image.ImageSource = 'sdof_base.jpg';
            app.Label = uilabel(app.SDOFResponseTab);
            app.Label.Text = 'The Base Excitation Array must have two columns:  time (sec) & accel (G)';
            app.BaseExcitationArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.BaseExcitationArrayEditFieldLabel.Text = 'Base Excitation Array';
            app.BaseExcitationArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.BaseExcitationArrayEditField.ValueChangingFcn = createCallbackFcn(app, @BaseExcitationArrayEditFieldValueChanging, true);
            app.AccelResponseArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.AccelResponseArrayEditFieldLabel.Text = 'Accel Response Array';
            app.AccelResponseArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.AccelResponseArrayEditField.Value = 'accel_response';
            app.RelDispResponseArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.RelDispResponseArrayEditFieldLabel.Text = 'Rel Disp Response Array';
            app.RelDispResponseArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.RelDispResponseArrayEditField.Value = 'rel_disp_response';
            app.CalculateListBoxLabel = uilabel(app.SDOFResponseTab);
            app.CalculateListBoxLabel.Text = 'Calculate';
            app.CalculateListBox = uilistbox(app.SDOFResponseTab);
            app.CalculateListBox.Items = {'Time Histories Only', 'Transmissibility', 'Transmissibility & Phase'};
            app.CalculateListBox.Value = 'Time Histories Only';
            app.Label_2 = uilabel(app.SDOFResponseTab);
            app.Label_2.Text = 'The numerical engine is the Smallwood ramp invariant digital recursive filtering relationship.';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.AmplificationFactorQEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            % Show the figure after all components are created
            app.UISineSweepUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_sine_sweep_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISineSweepUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISineSweepUIFigure)
        end
    end
end