classdef sine_curve_fit_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISineCurveFitUIFigure         matlab.ui.Figure
        sine_curve_fit_aver31byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SineTab                        matlab.ui.container.Tab
        NumberofSinesEditFieldLabel    matlab.ui.control.Label
        NumberofSinesEditField         matlab.ui.control.NumericEditField
        CalculateButton_Sine           matlab.ui.control.Button
        DampedSineTab                  matlab.ui.container.Tab
        CalculateButton_DampedSine     matlab.ui.control.Button
        NumberofDampedSinesEditFieldLabel  matlab.ui.control.Label
        NumberofDampedSinesEditField   matlab.ui.control.NumericEditField
        ZeroDelayOptionListBoxLabel    matlab.ui.control.Label
        ZeroDelayOptionListBox         matlab.ui.control.ListBox
        MinDampingRatioEditFieldLabel  matlab.ui.control.Label
        MinDampingRatioEditField       matlab.ui.control.NumericEditField
        MaxDampingRatioEditFieldLabel  matlab.ui.control.Label
        MaxDampingRatioEditField       matlab.ui.control.NumericEditField
        SaveDataTab                    matlab.ui.container.Tab
        SynthesisEditFieldLabel        matlab.ui.control.Label
        SynthesisEditField             matlab.ui.control.EditField
        ResidualEditFieldLabel         matlab.ui.control.Label
        ResidualEditField              matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        EnterData1Panel                matlab.ui.container.Panel
        CalculateButton_PlotInput      matlab.ui.control.Button
        InputArrayNameEditFieldLabel   matlab.ui.control.Label
        InputArrayNameEditField        matlab.ui.control.EditField
        PlotTitleEditFieldLabel        matlab.ui.control.Label
        PlotTitleEditField             matlab.ui.control.EditField
        YaxisLabelEditFieldLabel       matlab.ui.control.Label
        YaxisLabelEditField            matlab.ui.control.EditField
        RemoveMeanListBoxLabel         matlab.ui.control.Label
        RemoveMeanListBox              matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        EnterData2Panel                matlab.ui.container.Panel
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.NumericEditField
        MaxFreqHzEditFieldLabel        matlab.ui.control.Label
        MaxFreqHzEditField             matlab.ui.control.NumericEditField
        DownsampleperMaxFreqListBoxLabel  matlab.ui.control.Label
        DownsampleperMaxFreqListBox    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        THM      % Description
        t_string %
        tr       %
        tt       %
        ylab     %
        syn      %
        residual %
        synth_th    %
        residual_th %
    end
    
    methods (Access = private)
        
        function CloseFigures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
        
        function plot12(app)

            t=app.THM(:,1);
            
            s_tim=app.THM(:,1);
            s_amp=app.THM(:,2);
        
            fig_num=2;
  
            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),'b');
            xlabel('Time (sec)');
            ylabel(app.ylab);
            title(app.t_string);
            hold on
            try
                plot(app.tt,app.syn,'r');
            catch
                size(app.tt)
                size(app.syn)
            end
            xlabel(' Time (sec) ');
            legend ('Input Data','Synthesis');
            
            ymax=1.5*max(s_amp);
            ymin=1.5*min(s_amp);
            ylim([ymin,ymax]);
            grid on;
            hold off;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            [~,i1]=min(abs(s_tim-t(1)));
            [~,i2]=min(abs(s_tim-t(end)));
            
            
            if(i1>=2 && i2 <=length(t)-1)
            
                figure(fig_num);
                fig_num=fig_num+1;
                
                plot(s_tim(i1:i2),s_amp(i1:i2),'b');
                xlabel('Time (sec)');
                ylabel(app.ylab);
                title(app.t_string);
                hold on
                plot(app.tt,app.syn,'r');
                xlabel(' Time (sec) ');
                legend ('Input Data','Synthesis');
                
                grid on;
                hold off;
            
            end
            
            figure(fig_num);
            plot(app.tr,app.residual);
            xlabel('Time (sec)');
            ylabel(app.ylab);
            title(app.t_string);
            title('Residual')            
                    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalculateButton_Sine.Enable='off';
            app.CalculateButton_DampedSine.Enable='off';  
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton_PlotInput
        function CalculateButton_PlotInputPushed(app, event)
            CloseFigures(app);
            
            i=app.RemoveMeanListBox.Value;
  
            try
                FS=app.InputArrayNameEditField.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg(' Input array not found.');
                return; 
            end    
            
            if(strcmp(i,'Yes'))
                app.THM(:,2)=app.THM(:,2)-mean(app.THM(:,2));
            end
            
            app.t_string=app.PlotTitleEditField.Value;
            app.ylab=app.YaxisLabelEditField.Value;
            
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            out1=sprintf('Time History %s',app.t_string);
            title(out1);
            xlabel('Time (sec)');
            ylabel(app.ylab);
            grid on;
            
            tmi=app.THM(1,1);
            tmx=app.THM(end,1);
            
            dt=mean(diff(app.THM(:,1)));
            sr=1/dt;
            fmax=sr/2;
            
            tmi=str2num(sprintf('%8.5g',tmi));
            tmx=str2num(sprintf('%8.5g',tmx));
            fmax=str2num(sprintf('%8.5g',fmax));            
            
            app.StartTimesecEditField.Value=tmi;
            app.EndTimesecEditField.Value=tmx;            
            app.MaxFreqHzEditField.Value=fmax;
            
            app.CalculateButton_Sine.Enable='on';
            app.CalculateButton_DampedSine.Enable='on';            
            
        end

        % Button pushed function: CalculateButton_Sine
        function CalculateButton_SinePushed(app, event)
            CloseFigures(app);
           
            tic;
            
            disp(' ');
            disp(' * * * * * * ');
            disp(' ');
            disp(' Calculating... ');
            disp(' ');

            amp_orig=app.THM(:,2);
            
            t=app.THM(:,1);
            a=app.THM(:,2);
            
            tmi=app.StartTimesecEditField.Value;
            tmx=app.EndTimesecEditField.Value;
            
            [~,ii]=min(abs(t-tmi));
            [~,jj]=min(abs(t-tmx));
            
            t=t(ii:jj);
            a=a(ii:jj);
            
            dt=mean(diff(t));
          
            dur=t(end)-t(1);
              
            nfr=app.NumberofSinesEditField.Value;
            
            fmax=app.MaxFreqHzEditField.Value;
            sr=1/dt;
            
            id=app.DownsampleperMaxFreqListBox.Value;
            
            if(strcmp(id,'Yes'))
                new_sr=10*fmax;
                new_nyq=new_sr/2;
                freq=0.6*new_nyq;
                N=floor(sr/new_sr);
                fprintf('\n  sr=%8.4g Hz    new_sr=%8.4g Hz   LP freq=%8.4g Hz \n\n',sr,new_sr,freq);
                if(N>=2)
                    iband=1;
                    iphase=1;
                    [a,~,~,~]=Butterworth_filter_function(a,dt,iband,freq,iphase);
                    t=t(1:N:end);
                    a=a(1:N:end);
                    a = fix_size(a);
                    t = fix_size(t);  
                end
            end
           
            [app.syn,~,Ar,Br,omeganr,iflag]=sine_find_function_flag(dur,a,amp_orig,t,dt,nfr);
            
            app.residual=a-app.syn;
            
            if(iflag==1)
               warndlg('sfa error.  Check segment duration. '); 
               return;
            end
            
            s_tim=app.THM(:,1);
            s_amp=app.THM(:,2);
            
            tmax=1.0e+90;
            
            LM=length(s_tim);
            
            for i=1:LM
                terr=abs(s_tim(i)-t(1));
                if(terr<tmax)
                    tmax=terr;
                end
            end
            
            t=fix_size(t);
            app.syn=fix_size(app.syn);
            
            app.residual=fix_size(app.residual);
            
            app.synth_th=[t app.syn ];
            app.residual_th=[t app.residual];
            
            app.tt=app.synth_th(:,1);
            app.tr=app.residual_th(:,1);
            
            plot12(app);
         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            tp=2*pi;
            %
            %      y=Ar*cos(omeganr*tt) + Br*sin(omeganr*tt); 
            %
            
            mag=zeros(nfr,1);
            phase=zeros(nfr,1);
            
            
            disp(' ');
            disp(' Results');
            disp(' ');
            disp(' Case   Amplitude   fn(Hz)   Phase(rad)  ');
            %
            
            for ie=1:nfr
                mag(ie)=norm([Ar(ie) Br(ie)]);
                phase(ie)=atan2(Ar(ie),Br(ie));
                fprintf('  %d  %10.4f  %10.4f  %10.4f  \n',ie,mag(ie),omeganr(ie)/tp,phase(ie));
            end    
            
            omeganr=fix_size(omeganr);
            
            aaa=[mag omeganr/tp phase];
            bbb=sortrows(aaa,2);
            setappdata(0,'curve_fit_table',bbb);

            disp(' ');
            disp(' ');
            disp(' Calculation complete ');
            disp(' ');
            toc
            
        end

        % Button pushed function: CalculateButton_DampedSine
        function CalculateButton_DampedSinePushed(app, event)

            CloseFigures(app);
           
            tic;
            
            disp(' ');
            disp(' * * * * * * ');
            disp(' ');
            disp(' Calculating... ');
            disp(' ');

            amp_orig=app.THM(:,2);
            
            t=app.THM(:,1);
            a=app.THM(:,2);
            
            tmi=app.StartTimesecEditField.Value;
            tmx=app.EndTimesecEditField.Value;
            
            [~,ii]=min(abs(t-tmi));
            [~,jj]=min(abs(t-tmx));
            
            t=t(ii:jj);
            a=a(ii:jj);
            
            i=app.RemoveMeanListBox.Value;
            
            if(strcmp(i,'Yes'))
                a=a-mean(a);
                app.THM(:,2)=app.THM(:,2)-mean(app.THM(:,2));
            end
            
            dt=mean(diff(t));
          
            dur=t(end)-t(1);
              
            nfr=app.NumberofDampedSinesEditField.Value;
            
            fmax=app.MaxFreqHzEditField.Value;
            sr=1/dt;
            
            id=app.DownsampleperMaxFreqListBox.Value;
            
            if(strcmp(id,'Yes'))

                new_sr=10*fmax;
                new_nyq=new_sr/2;
                freq=0.6*new_nyq;
                N=floor(sr/new_sr);
                fprintf('\n  sr=%8.4g Hz    new_sr=%8.4g Hz   LP freq=%8.4g Hz  N=%d \n\n',sr,new_sr,freq,N);
                if(N>=2)
                    iband=1;
                    iphase=1;
                    [a,~,~,~]=Butterworth_filter_function(a,dt,iband,freq,iphase);
                    t=t(1:N:end);
                    a=a(1:N:end);
                    a = fix_size(a);
                    t = fix_size(t);  
                end 
            end
            
            av=a;
            
            md1=app.MinDampingRatioEditField.Value;
            md2=app.MaxDampingRatioEditField.Value;  
            
            zd=app.ZeroDelayOptionListBox.Value;
            
            if(strcmp(zd,'On'))
                ndel=1;
            else
                ndel=2;
            end
            
            tp=2*pi;
            
            sr=1/dt;
            %
            fprintf(' SR  = %g samples/sec    dt = %g sec \n\n',sr,dt);
            fprintf(' start  = %g sec    end = %g sec       \n',tmi,tmx);
            fprintf(' ndel = %d \n',ndel);
            
            clear app.syn;
            
            if(ndel==1)
                [app.syn,~,Ar,Br,omeganr,dampr]=damped_sine_find_function_alt(dur,av,amp_orig,t,dt,nfr,md1,md2,fmax);
            else
                [app.syn,~,Ar,Br,omeganr,dampr,delayr]=damped_sine_find_function_delay_alt(dur,av,amp_orig,t,dt,nfr,md1,md2,fmax);  
                dr=min(delayr);
                
                [~,jj]=min(abs(t-(t(1)+dr)));
                
                app.syn=app.syn(jj:end);
                t=t(jj:end);
            end
            
            app.syn=fix_size(app.syn);
            
            s_tim=app.THM(:,1);
            s_amp=app.THM(:,2);
            
            % % % % % %
            
            %
            
            tmax=1.0e+90;
            
            LM=length(s_tim);
            
            for i=1:LM
                terr=abs(s_tim(i)-t(1));
                if(terr<tmax)
                    tmax=terr;
                end
            end
             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            

            N=length(t);
            
            app.residual=zeros(N,1);
            
            for i=1:N
                [~,ijk]=min(abs(s_tim-t(i)));
                app.residual(i)=s_amp(ijk)-app.syn(i);
            end
            
            t=fix_size(t);
            app.syn=fix_size(app.syn);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            mag=zeros(nfr,1);
            phase=zeros(nfr,1);
            
            omeganr=fix_size(omeganr);
           
            for ie=1:nfr    
                mag(ie)=norm([Ar(ie) Br(ie)]);
                phase(ie)=atan2(Ar(ie),Br(ie));
            end        
            
            disp(' ');
            disp(' Results');
            disp(' ');
            
            if(ndel==1)
                
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp ');
            
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f \n',ie,mag(ie),omeganr(ie)/tp,phase(ie),dampr(ie));   
                end  
                
                aaa=[mag omeganr/tp phase dampr];
                
            else
                
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp  delay(sec)');    
             
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f  %8.4f \n',ie,mag(ie),omeganr(ie)/tp,phase(ie),dampr(ie),delayr(ie));      
                end       
                
                aaa=[mag omeganr/tp phase dampr delayr];
                
            end
            
            bbb=sortrows(aaa,2);
            setappdata(0,'curve_fit_table',bbb);
            
            ccc=sortrows(aaa,1,'descend');
            
            disp(' ');
            disp(' Results Sorted by Frequency');
            disp(' ');
            
            if(ndel==1)
            
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp');
            
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f \n',ie,bbb(ie,1),bbb(ie,2),bbb(ie,3),bbb(ie,4));      
                end   
                
            else
                
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp   delay(sec)');
            
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f  %8.4f \n',ie,bbb(ie,1),bbb(ie,2),bbb(ie,3),bbb(ie,4),bbb(ie,5));
                end   
                
            end
            
            disp(' ');
            disp(' Results Sorted by Amplitude');
            disp(' ');
            
            if(ndel==1)
            
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp');
            
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f \n',ie,ccc(ie,1),ccc(ie,2),ccc(ie,3),ccc(ie,4));      
                end   
                
            else
                
                disp(' Case   Amplitude   fn(Hz)   Phase(rad)  damp   delay(sec)');
            
                for ie=1:nfr
                    fprintf('  %d  %10.4f  %10.4f  %10.4f  %8.4f  %8.4f \n',ie,ccc(ie,1),ccc(ie,2),ccc(ie,3),ccc(ie,4),ccc(ie,5));
                end   
                 
            end
            
            
            fprintf('\n Plotting results... \n');

            
            app.synth_th=[t app.syn ];
            app.residual_th=[t app.residual]; 
            app.tt=app.synth_th(:,1);
            app.tr=app.residual_th(:,1);
   
            plot12(app);
                    
            
            fprintf('\n\n Calculation complete \n\n');
          
            toc
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output_name=app.SynthesisEditField.Value;
            assignin('base', output_name, app.synth_th);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            output_name=app.ResidualEditField.Value;
            assignin('base', output_name, app.residual_th);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISineCurveFitUIFigure = uifigure('Visible', 'off');
            app.UISineCurveFitUIFigure.Name = 'UI SineCurveFit';
            app.sine_curve_fit_aver31byTomIrvineLabel = uilabel(app.UISineCurveFitUIFigure);
            app.sine_curve_fit_aver31byTomIrvineLabel.Text = 'sine_curve_fit_a    ver 3.1   by Tom Irvine';
            app.ReturnButton = uibutton(app.UISineCurveFitUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.UISineCurveFitUIFigure);
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.NumberofSinesEditFieldLabel = uilabel(app.SineTab);
            app.NumberofSinesEditFieldLabel.Text = 'Number of Sines';
            app.NumberofSinesEditField = uieditfield(app.SineTab, 'numeric');
            app.NumberofSinesEditField.Value = 1;
            app.CalculateButton_Sine = uibutton(app.SineTab, 'push');
            app.CalculateButton_Sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SinePushed, true);
            app.CalculateButton_Sine.Text = 'Calculate';
            app.DampedSineTab = uitab(app.TabGroup);
            app.DampedSineTab.Title = 'Damped Sine';
            app.CalculateButton_DampedSine = uibutton(app.DampedSineTab, 'push');
            app.CalculateButton_DampedSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_DampedSinePushed, true);
            app.CalculateButton_DampedSine.Text = 'Calculate';
            app.NumberofDampedSinesEditFieldLabel = uilabel(app.DampedSineTab);
            app.NumberofDampedSinesEditFieldLabel.Text = 'Number of Damped Sines';
            app.NumberofDampedSinesEditField = uieditfield(app.DampedSineTab, 'numeric');
            app.NumberofDampedSinesEditField.Value = 1;
            app.ZeroDelayOptionListBoxLabel = uilabel(app.DampedSineTab);
            app.ZeroDelayOptionListBoxLabel.Text = 'Zero Delay Option';
            app.ZeroDelayOptionListBox = uilistbox(app.DampedSineTab);
            app.ZeroDelayOptionListBox.Items = {'On', 'Off'};
            app.ZeroDelayOptionListBox.Value = 'On';
            app.MinDampingRatioEditFieldLabel = uilabel(app.DampedSineTab);
            app.MinDampingRatioEditFieldLabel.Text = 'Min Damping Ratio';
            app.MinDampingRatioEditField = uieditfield(app.DampedSineTab, 'numeric');
            app.MaxDampingRatioEditFieldLabel = uilabel(app.DampedSineTab);
            app.MaxDampingRatioEditFieldLabel.Text = 'Max Damping Ratio';
            app.MaxDampingRatioEditField = uieditfield(app.DampedSineTab, 'numeric');
            app.MaxDampingRatioEditField.Value = 0.2;
            app.SaveDataTab = uitab(app.TabGroup);
            app.SaveDataTab.Title = 'Save Data';
            app.SynthesisEditFieldLabel = uilabel(app.SaveDataTab);
            app.SynthesisEditFieldLabel.Text = 'Synthesis';
            app.SynthesisEditField = uieditfield(app.SaveDataTab, 'text');
            app.ResidualEditFieldLabel = uilabel(app.SaveDataTab);
            app.ResidualEditFieldLabel.Text = 'Residual';
            app.ResidualEditField = uieditfield(app.SaveDataTab, 'text');
            app.SaveButton = uibutton(app.SaveDataTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_2 = uibutton(app.SaveDataTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.EnterData1Panel = uipanel(app.UISineCurveFitUIFigure);
            app.EnterData1Panel.Title = 'Enter Data 1';
            app.CalculateButton_PlotInput = uibutton(app.EnterData1Panel, 'push');
            app.CalculateButton_PlotInput.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PlotInputPushed, true);
            app.CalculateButton_PlotInput.Text = 'Plot Time History';
            app.InputArrayNameEditFieldLabel = uilabel(app.EnterData1Panel);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.EnterData1Panel, 'text');
            app.PlotTitleEditFieldLabel = uilabel(app.EnterData1Panel);
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.EnterData1Panel, 'text');
            app.YaxisLabelEditFieldLabel = uilabel(app.EnterData1Panel);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.EnterData1Panel, 'text');
            app.RemoveMeanListBoxLabel = uilabel(app.EnterData1Panel);
            app.RemoveMeanListBoxLabel.Text = 'Remove Mean';
            app.RemoveMeanListBox = uilistbox(app.EnterData1Panel);
            app.RemoveMeanListBox.Items = {'Yes', 'No'};
            app.RemoveMeanListBox.Value = 'Yes';
            app.Label = uilabel(app.UISineCurveFitUIFigure);
            app.Label.Text = 'This script performs either a sine or damped-sine curve fit on a time history.  The input array must have two columns:  Time(sec) & Amplitude';
            app.EnterData2Panel = uipanel(app.UISineCurveFitUIFigure);
            app.EnterData2Panel.Title = 'Enter Data 2';
            app.StartTimesecEditFieldLabel = uilabel(app.EnterData2Panel);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.EnterData2Panel, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.EnterData2Panel);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.EnterData2Panel, 'numeric');
            app.MaxFreqHzEditFieldLabel = uilabel(app.EnterData2Panel);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.EnterData2Panel, 'numeric');
            app.DownsampleperMaxFreqListBoxLabel = uilabel(app.EnterData2Panel);
            app.DownsampleperMaxFreqListBoxLabel.Text = 'Downsample per Max Freq';
            app.DownsampleperMaxFreqListBox = uilistbox(app.EnterData2Panel);
            app.DownsampleperMaxFreqListBox.Items = {'Yes', 'No'};
            app.DownsampleperMaxFreqListBox.Value = 'Yes';
            % Show the figure after all components are created
            app.UISineCurveFitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sine_curve_fit_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISineCurveFitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISineCurveFitUIFigure)
        end
    end
end