classdef sine_sweep_parameters_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineSweepUIFigure              matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        CalculationTab                 matlab.ui.container.Tab
        WindowCyclesEditField          matlab.ui.control.EditField
        WindowCyclesEditFieldLabel     matlab.ui.control.Label
        OutputFunctionsListBox_2       matlab.ui.control.ListBox
        OutputFunctionsListBox_2Label  matlab.ui.control.Label
        text1_2                        matlab.ui.control.Label
        ListboxFormat                  matlab.ui.control.ListBox
        text1                          matlab.ui.control.Label
        ListboxMethod                  matlab.ui.control.ListBox
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        SelectResponseArrayListBox     matlab.ui.control.ListBox
        SelectResponseArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        ReadDataButton                 matlab.ui.control.Button
        CalculateButton_2              matlab.ui.control.Button
        InputListBox                   matlab.ui.control.ListBox
        InputListBoxLabel              matlab.ui.control.Label
        ResponseListBox                matlab.ui.control.ListBox
        ResponseListBoxLabel           matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        OutputFunctionsListBox         matlab.ui.control.ListBox
        OutputFunctionsListBoxLabel    matlab.ui.control.Label
        OutputArrayEditField           matlab.ui.control.EditField
        OutputArrayEditFieldLabel      matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        sine_sweep_parameters_ahver15byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OctavesTab                     matlab.ui.container.Tab
        NumberofOctavesEditField       matlab.ui.control.EditField
        NumberofOctavesEditFieldLabel  matlab.ui.control.Label
        CalculatenumberofoctavesbetweentwofrequenciesLabel  matlab.ui.control.Label
        CalculateNumberButton          matlab.ui.control.Button
        UpperFreqHzEditField           matlab.ui.control.EditField
        UpperFreqHzEditFieldLabel      matlab.ui.control.Label
        LowerFreqHzEditField           matlab.ui.control.EditField
        LowerFreqHzEditFieldLabel      matlab.ui.control.Label
        CrossOverFreqTab               matlab.ui.container.Tab
        unit2Label                     matlab.ui.control.Label
        unit1Label                     matlab.ui.control.Label
        Amplitude2EditField            matlab.ui.control.EditField
        Amplitude2EditFieldLabel       matlab.ui.control.Label
        CrossOverFreqEditField         matlab.ui.control.EditField
        CrossOverFreqEditFieldLabel    matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Amplitude1EditField            matlab.ui.control.EditField
        Amplitude1EditFieldLabel       matlab.ui.control.Label
        AmplitudePairsListBox          matlab.ui.control.ListBox
        AmplitudePairsListBoxLabel     matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        SweepRateTotalCyclesTab        matlab.ui.container.Tab
        SetDurationto1octminButton     matlab.ui.control.Button
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        CalculateRateButton            matlab.ui.control.Button
        UnitListBox                    matlab.ui.control.ListBox
        UnitListBoxLabel               matlab.ui.control.Label
        DurationEditField              matlab.ui.control.EditField
        DurationEditFieldLabel         matlab.ui.control.Label
        UpperFreqHzEditField_2         matlab.ui.control.EditField
        UpperFreqHzEditField_2Label    matlab.ui.control.Label
        LowerFreqHzEditField_2         matlab.ui.control.EditField
        LowerFreqHzEditField_2Label    matlab.ui.control.Label
        SweepRateListBox               matlab.ui.control.ListBox
        SweepRateListBoxLabel          matlab.ui.control.Label
        SineSweepTransTab              matlab.ui.container.Tab
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        nunit % Description
        npair %
        THM   %
        a     %
        b     %
        t     %
        an    %
        au    %
        bn    %
        bu    %
        iau   %
        ibu   %
        trans %
        trans_phase %
    end
    
    methods (Access = private)
     
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end       
        
        function CO_change(app)

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.nunit]=find_index(Items,im);

            Items = char(app.AmplitudePairsListBox.Items);
            im=app.AmplitudePairsListBox.Value;
            [app.npair]=find_index(Items,im);           
            
            if(app.npair==1)        % disp (zero-peak) -> velocity
                ttext1='displacement (zero-peak)';
                ttext2='velocity';
                if(app.nunit==1) % G, in/sec, in
                    text_unit_1 = 'inch';
                    text_unit_2 = 'in/sec';        
                end
                if(app.nunit==2) % G, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'm/sec';           
                end
                if(app.nunit==3) % m/sec^2, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'm/sec';          
                end    
            end
            
            if(app.npair==2)     % disp (zero-peak) -> acceleration 
                ttext1='displacement (zero-peak)';
                ttext2='acceleration';    
                if(app.nunit==1) % G, in/sec, in
                    text_unit_1 = 'inch';
                    text_unit_2 = 'G';          
                end
                if(app.nunit==2) % G, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'G';        
                end
                if(app.nunit==3) % m/sec^2, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'm/sec^2';        
                end 
            end
            
            if(app.npair==3)     % disp (peak-peak) -> velocity 
                ttext1='displacement (peak-peak)';
                ttext2='velocity';     
                if(app.nunit==1) % G, in/sec, in
                    text_unit_1 = 'inch';
                    text_unit_2 = 'in/sec';          
                end
                if(app.nunit==2) % G, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'm/sec';          
                end
                if(app.nunit==3) % m/sec^2, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'm/sec';         
                end 
            end
            
            if(app.npair==4)     % disp (peak-peak) -> acceleration
                ttext1='displacement (peak-peak)';
                ttext2='acceleration';     
                if(app.nunit==1) % G, in/sec, in
                    text_unit_1 = 'inch';
                    text_unit_2 = 'G';         
                end
                if(app.nunit==2) % G, m/sec, mm
                    text_unit_1 = 'mm';
                    text_unit_2 = 'G';          
                end
                if(app.nunit==3) % m/sec^2, m/sec, mm
                    text_unit_1 = 'mm'; 
                    text_unit_2 = 'm/sec^2';         
                end 
            end
            
            if(app.npair==5)     % velocity -> acceleration
                ttext1='velocity';
                ttext2='acceleration';     
                if(app.nunit==1) % G, in/sec, in
                    text_unit_1 = 'in/sec';        
                    text_unit_2 = 'G';          
                end
                if(app.nunit==2) % G, m/sec, mm
                    text_unit_1 = 'm/sec';
                    text_unit_2 = 'G';        
                end
                if(app.nunit==3) % m/sec^2, m/sec, mm
                   text_unit_1 = 'm/sec';
                   text_unit_2 = 'm/sec^2';      
                end 
            end

            app.Amplitude1EditFieldLabel.Text=ttext1;
            app.Amplitude2EditFieldLabel.Text=ttext2;

            app.unit1Label.Text=text_unit_1;
            app.unit2Label.Text=text_unit_2;
        end
        
        function Trans_change(app)

            update(app);

            app.CalculateButton_2.Enable='off';

            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            app.SelectInputArrayListBox.Visible='off';
            app.SelectInputArrayListBoxLabel.Visible='off';   
            app.SelectResponseArrayListBox.Visible='off';
            app.SelectResponseArrayListBoxLabel.Visible='off';  

            if(n==1)

                app.SelectInputArrayListBox.Visible='on';
                app.SelectInputArrayListBoxLabel.Visible='on'; 
                
                if(m==1)
                    ss=sprintf('Select Common Input Array');
                else
                    ss=sprintf('Select Base Input Array');
                    app.SelectResponseArrayListBox.Visible='on';
                    app.SelectResponseArrayListBoxLabel.Visible='on';                
                end

                app.SelectInputArrayListBoxLabel.Text=ss; 

            end    
        end
        
        function update(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                sz=vars(i).size;
 
                if( (contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list'))  && (sz(2)==2 || sz(2)==3)) 
                    cell_array{i} ='999';
                else    
                    if(sz(2)==2 || sz(2)==3)
                        cell_array{i} = vars(i).name;
                    else
                        cell_array{i} ='999';
                    end  
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.SelectResponseArrayListBox.Items=unique(sort(cell_array));             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CO_change(app);
            Trans_change(app);
            app.NumberofOctavesEditField.Visible='off';
            app.NumberofOctavesEditFieldLabel.Visible='off';
            app.CrossOverFreqEditField.Visible='off';
            app.CrossOverFreqEditFieldLabel.Visible='off';   
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';              
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateNumberButton
        function CalculateNumberButtonPushed(app, event)
            f1=str2num(app.LowerFreqHzEditField.Value);
            f2=str2num(app.UpperFreqHzEditField.Value);  

            fmin=min([f1 f2]);
            fmax=max([f1 f2]);

            noct=log2(fmax/fmin);

            ss=sprintf('%7.2f',noct);
            app.NumberofOctavesEditField.Value=ss;
            
            app.NumberofOctavesEditField.Visible='on';
            app.NumberofOctavesEditFieldLabel.Visible='on';
        end

        % Value changing function: LowerFreqHzEditField
        function LowerFreqHzEditFieldValueChanging(app, event)
            app.NumberofOctavesEditField.Visible='off';
            app.NumberofOctavesEditFieldLabel.Visible='off';
        end

        % Value changing function: UpperFreqHzEditField
        function UpperFreqHzEditFieldValueChanging(app, event)
            app.NumberofOctavesEditField.Visible='off';
            app.NumberofOctavesEditFieldLabel.Visible='off';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            amp1=str2num(app.Amplitude1EditField.Value);
            amp2=str2num(app.Amplitude2EditField.Value);
            
            if(app.npair==1 || app.npair==3)
                d=amp1;
                v=amp2;
            end    
            if(app.npair==2 || app.npair==4)
                d=amp1;
                app.a=amp2;
            end 
            if(app.npair==5)
                v=amp1;
                app.a=amp2;
            end    
            
            if(app.nunit==1) % G, in/sec, in
                if(app.npair==1) % disp (zero-peak) -> velocity
                end
                if(app.npair==2) % disp (zero-peak) -> acceleration
                    app.a=app.a*386;
                end
                if(app.npair==3) % disp (peak-peak) -> velocity
                    d=d/2;
                end
                if(app.npair==4) % disp (peak-peak) -> acceleration
                    d=d/2;
                    app.a=app.a*386;
                end
                if(app.npair==5) % velocity -> acceleration
                    app.a=app.a*386;
                end   
            end
            if(app.nunit==2) % G, m/sec, mm
                d=d/1000;
                if(app.npair==1) % disp (zero-peak) -> velocity
                end
                if(app.npair==2) % disp (zero-peak) -> acceleration
                    app.a=app.a*9.81;
                end
                if(app.npair==3) % disp (peak-peak) -> velocity
                    d=d/2;
                end
                if(app.npair==4) % disp (peak-peak) -> acceleration
                    d=d/2; 
                    app.a=app.a*9.81;
                end
                if(app.npair==5) % velocity -> acceleration
                    app.a=app.a*9.81;
                end   
            
            end
            if(app.nunit==3) % m/sec^2, m/sec, mm
                d=d/1000;
                if(app.npair==1) % disp (zero-peak) -> velocity
                end
                if(app.npair==2) % disp (zero-peak) -> acceleration
                end
                if(app.npair==3) % disp (peak-peak) -> velocity
                    d=d/2;        
                end
                if(app.npair==4) % disp (peak-peak) -> acceleration
                    d=d/2; 
                end
                if(app.npair==5) % velocity -> acceleration
                end   
            end
            
            if(app.npair==1 || app.npair==3)
                fc=( v/d );
            end    
            if(app.npair==2 || app.npair==4)
                fc=sqrt( app.a/d );
            end 
            if(app.npair==5)
                fc=( app.a/v );
            end    
            fc=fc/(2*pi);
            
            out1=sprintf('%8.4g Hz',fc);
            
            app.CrossOverFreqEditField.Value=out1;

            app.CrossOverFreqEditField.Visible='on';
            app.CrossOverFreqEditFieldLabel.Visible='on';
 
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            CO_change(app);
        end

        % Value changed function: AmplitudePairsListBox
        function AmplitudePairsListBoxValueChanged(app, event)
            CO_change(app);
        end

        % Button pushed function: CalculateRateButton
        function CalculateRateButtonPushed(app, event)
            Items = char(app.SweepRateListBox.Items);
            im=app.SweepRateListBox.Value;
            [nr]=find_index(Items,im);
            
            Items = char(app.UnitListBox.Items);
            im=app.UnitListBox.Value;
            [nu]=find_index(Items,im);

            dur=str2num(app.DurationEditField.Value);

            if(nu==2)
                dur=dur/60;
            end    

            f1=str2num(app.LowerFreqHzEditField_2.Value);
            f2=str2num(app.UpperFreqHzEditField_2.Value);  

            fmin=min([f1 f2]);
            fmax=max([f1 f2]);

            noct=log2(fmax/fmin);   

            T=dur*60;

            if(nr==1) % log
                rate=noct/dur;
                RR=rate/60;
                cycles=f1*( -1.+ 2^(RR*T) )/( RR*log(2) );
                ss=sprintf('Number of octaves = %7.2f \n\nSweep Rate = %7.2f octaves/min \n\nTotal Cycles = %8.5g',noct,rate,cycles); 
            else
                rate=(fmax-fmin)/dur;
                cycles=0.5*( (f2-f1) / T  )*T^2 + f1*T;  % linear ok
                ss=sprintf('Number of octaves = %7.2f \n\nSweep Rate = %7.2f Hz/sec \n\nTotal Cycles = %8.5g',noct,rate/60,cycles); 
            end

            app.ResultsTextArea.Value=ss; 
            app.ResultsTextArea.Visible='on';  
            app.ResultsTextAreaLabel.Visible='on'; 
            
        end

        % Value changed function: SweepRateListBox
        function SweepRateListBoxValueChanged(app, event)
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';  
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';  
        end

        % Value changing function: DurationEditField
        function DurationEditFieldValueChanging(app, event)
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';  
        end

        % Value changing function: LowerFreqHzEditField_2
        function LowerFreqHzEditField_2ValueChanging(app, event)
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';  
        end

        % Value changing function: UpperFreqHzEditField_2
        function UpperFreqHzEditField_2ValueChanging(app, event)
            app.ResultsTextArea.Visible='off';  
            app.ResultsTextAreaLabel.Visible='off';  
        end

        % Button pushed function: SetDurationto1octminButton
        function SetDurationto1octminButtonPushed(app, event)
            f1=str2num(app.LowerFreqHzEditField_2.Value);
            f2=str2num(app.UpperFreqHzEditField_2.Value);  

            fmin=min([f1 f2]);
            fmax=max([f1 f2]);

            noct=log2(fmax/fmin);

            Tmin=noct/1;
            sec=Tmin*60;

            ss=sprintf('%g',round(sec));

            app.SweepRateListBox.Value='log';
            app.UnitListBox.Value='sec';
       
            app.DurationEditField.Value=ss;

            CalculateRateButtonPushed(app, event);

        end

        % Value changed function: ListboxMethod
        function ListboxMethodValueChanged(app, event)
            Trans_change(app);
        end

        % Value changed function: ListboxFormat
        function ListboxFormatValueChanged(app, event)
            Trans_change(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            
            ClosePlots(app);

            Items = strtrim(app.InputListBox.Items);
            im = strtrim(app.InputListBox.Value);
            app.iau = find(strcmp(Items, im));

            Items = strtrim(app.ResponseListBox.Items);
            im = strtrim(app.ResponseListBox.Value);
            app.ibu = find(strcmp(Items, im));
            
            if(app.iau==1)
                app.an='Accel';
                app.au='G';
            end    
            if(app.iau==2)
                app.an='Force';
                app.au='lbf';
            end  
            if(app.iau==3)
                app.an='Force';
                app.au='N';
            end                          
            if(app.iau==4)
                app.an='Pressure';
                app.au='psi';
            end  
            if(app.iau==5)
                app.an='Pressure';
                app.au='Pa';
            end  

            if(app.ibu==1)
                app.bn='Accel';
                app.bu='G';
            end 
            if(app.ibu==2)
                app.bn='Velocity';
                app.bu='in/sec';
            end 
            if(app.ibu==3)
                app.bn='Velocity';
                app.bu='m/sec';
            end 
            if(app.ibu==4)
                app.bn='Displacement';
                app.bu='in';
            end            
            if(app.ibu==5)
                app.bn='Displacement';
                app.bu='m';
            end 
            if(app.ibu==6)
                app.bn='Displacement';
                app.bu='mm';
            end 
            if(app.ibu==7)
                app.bn='Pressure';
                app.bu='psi';
            end 
            if(app.ibu==8)
                app.bn='Pressure';
                app.bu='Pa';
            end        


            Items = char(app.ListboxMethod.Items);
            im=app.ListboxMethod.Value;
            [n]=find_index(Items,im);    

            Items = char(app.ListboxFormat.Items);
            im=app.ListboxFormat.Value;
            [m]=find_index(Items,im);

            if(n==1)

                if(m==1)
                    try
                        FS=app.SelectInputArrayListBox.Value;
                        app.THM=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end
                else

                    try
                        FS=app.SelectInputArrayListBox.Value;
                        F1=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end

                    try
                        FS=app.SelectResponseArrayListBox.Value;
                        F2=evalin('base',FS);
                    catch
                        warndlg('Input Array does not exist.  Try reading in external file.')
                        return;
                    end                    
   
                    try
                        app.THM=[F1 F2(:,2)];
                    catch
                        warndlg('Arrays not compatible');
                        return;
                    end    

                end

            else
                if(m==1)
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       app.THM = fscanf(fid,'%g %g %g',[2 inf]);
                       app.THM=app.THM';
                
                       size(app.THM)
    
                       msgbox('File read');              
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end
                else    
                    try
            
                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F1 = fscanf(fid,'%g %g',[2 inf]);
                       F1=F1';
                
                       sz1=size(F1);
    
                       msgbox('File 1 read'); 

                       [filename, pathname] = uigetfile('*.*');
                       filename = fullfile(pathname, filename);
                       fid = fopen(filename,'r');
                       F2 = fscanf(fid,'%g %g',[2 inf]);
                       F2=F2';
                
                       sz2=size(F2);
    
                       msgbox('File 2 read');

                       if(sz1(1)~=sz2(1))
                            warndlg('File 1 & File 2 have different lengths')
                            return;
                       else    
                            app.THM=[F1 F2(:,2)];
                       end     
    
                   catch
                       warndlg('Read unsuccessful');
                       return;
                   end                
                end
            end    
            
            try
                assignin('base','sine_sweep_array',app.THM);
                app.CalculateButton_2.Enable='on';
            catch
                warndlg('ref 2')
                return;
            end


            app.t=app.THM(:,1);
            t1=app.t;
            t2=app.t;
            app.a=app.THM(:,2);
            app.b=app.THM(:,3);   

            figure(1);
            plot(t1,app.a);
            grid on;
            xlabel('Time(sec)');
            ylabel(sprintf('%s (%s)',app.an,app.au));
            title('Input Signal');
            
            figure(2);
            plot(t2,app.b);
            grid on;
            xlabel('Time(sec)');
            ylabel(sprintf('%s (%s)',app.bn,app.bu));
            title('Response Signal');
            
            if(app.iau==app.ibu)

                figure(3);
                plot(t1,app.a,t2,app.b);
                grid on;
                xlabel('Time(sec)');
                ylabel(sprintf('%s (%s)',app.an,app.au));
                legend('Input','Response');
                title('Sine Sweep Time Histories');

            end

        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)

            fig_num=4;
  
            Items = char(app.OutputFunctionsListBox_2.Items);
            im=app.OutputFunctionsListBox_2.Value;
            [ncalc]=find_index(Items,im);

            
            ijk=ncalc+1;

           nnum=str2num(app.WindowCyclesEditField.Value);

            [f,app.trans,phase]=sine_sweep_transw([app.t app.a],app.b,ijk,nnum);


            if(app.iau==1)
                phase=-phase;
            end    
            
      
            % disp(' ref 4 ');
            
            figure(fig_num);
            fig_num=fig_num+1;
            
            plot(f,app.trans);
            title('Sine Sweep Transmissibility');
            xlabel('Frequency (Hz)');

            ylab=sprintf('Trans (%s/%s)',app.bu,app.au);
            
            ylabel(ylab); 

            grid on;
            
            xl = xlim;
            fmin=xl(1);
            fmax=xl(2);
            
            app.trans=[f app.trans];
            
            % disp(' ref 5 ');
            
            if(ncalc==2)
                
                app.trans_phase=[app.trans phase];
                
                phase=[f phase];
                
                t_string='Sine Sweep Magnitude & Phase';
                
                ff=f;
                FRF_p=phase(:,2);
                FRF_m=app.trans(:,2);

                md=4;
                
                [fig_num]=...
                plot_magnitude_phase_function_linear(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
                
            end
            
            try
                [fmax,amax]=find_max(app.trans);
            catch
                warndlg('find_max failed');
                return;
            end

            % disp(' ');
            % disp('* * * * * * * * * ');
            % disp(' ');
            
            % fprintf('\n  Maximum Transmissibility: (%8.4g Hz, %8.4g) \n\n',amax,fmax);


        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            Items = strtrim(app.OutputFunctionsListBox.Items);
            im = strtrim(app.OutputFunctionsListBox.Value);
            n = find(strcmp(Items, im));
                       
            if(n==1)
                data=app.trans;
            else
                data=app.trans_phase;
            end    

            output_name=app.OutputArrayEditField.Value;

            assignin('base',output_name,data);

            msgbox('Calculation complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineSweepUIFigure = uifigure('Visible', 'off');
            app.SineSweepUIFigure.Name = 'Sine Sweep';
            app.ReturnButton = uibutton(app.SineSweepUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.SineSweepUIFigure);
            app.OctavesTab = uitab(app.TabGroup);
            app.OctavesTab.Title = 'Octaves';
            app.LowerFreqHzEditFieldLabel = uilabel(app.OctavesTab);
            app.LowerFreqHzEditFieldLabel.Text = 'Lower Freq (Hz)';
            app.LowerFreqHzEditField = uieditfield(app.OctavesTab, 'text');
            app.LowerFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowerFreqHzEditFieldValueChanging, true);
            app.UpperFreqHzEditFieldLabel = uilabel(app.OctavesTab);
            app.UpperFreqHzEditFieldLabel.Text = 'Upper Freq (Hz)';
            app.UpperFreqHzEditField = uieditfield(app.OctavesTab, 'text');
            app.UpperFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @UpperFreqHzEditFieldValueChanging, true);
            app.CalculateNumberButton = uibutton(app.OctavesTab, 'push');
            app.CalculateNumberButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNumberButtonPushed, true);
            app.CalculateNumberButton.Text = 'Calculate Number';
            app.CalculatenumberofoctavesbetweentwofrequenciesLabel = uilabel(app.OctavesTab);
            app.CalculatenumberofoctavesbetweentwofrequenciesLabel.Text = 'Calculate number of octaves between two frequencies.';
            app.NumberofOctavesEditFieldLabel = uilabel(app.OctavesTab);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.OctavesTab, 'text');
            app.CrossOverFreqTab = uitab(app.TabGroup);
            app.CrossOverFreqTab.Title = 'Cross-Over Freq';
            app.UnitsListBoxLabel = uilabel(app.CrossOverFreqTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.CrossOverFreqTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm', 'm/sec^2, m/sec, mm'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.AmplitudePairsListBoxLabel = uilabel(app.CrossOverFreqTab);
            app.AmplitudePairsListBoxLabel.Text = 'Amplitude Pairs';
            app.AmplitudePairsListBox = uilistbox(app.CrossOverFreqTab);
            app.AmplitudePairsListBox.Items = {'disp (zero-peak) -> velocity', 'disp (zero-peak) -> acceleration', 'disp (peak-peak) -> velocity', 'disp (peak-peak) -> acceleration', 'velocity -> acceleration'};
            app.AmplitudePairsListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudePairsListBoxValueChanged, true);
            app.AmplitudePairsListBox.Value = 'disp (zero-peak) -> velocity';
            app.Amplitude1EditFieldLabel = uilabel(app.CrossOverFreqTab);
            app.Amplitude1EditFieldLabel.Text = 'Amplitude 1';
            app.Amplitude1EditField = uieditfield(app.CrossOverFreqTab, 'text');
            app.CalculateButton = uibutton(app.CrossOverFreqTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CrossOverFreqEditFieldLabel = uilabel(app.CrossOverFreqTab);
            app.CrossOverFreqEditFieldLabel.Text = 'Cross-Over Freq';
            app.CrossOverFreqEditField = uieditfield(app.CrossOverFreqTab, 'text');
            app.Amplitude2EditFieldLabel = uilabel(app.CrossOverFreqTab);
            app.Amplitude2EditFieldLabel.Text = 'Amplitude 2';
            app.Amplitude2EditField = uieditfield(app.CrossOverFreqTab, 'text');
            app.unit1Label = uilabel(app.CrossOverFreqTab);
            app.unit1Label.Text = 'unit1';
            app.unit2Label = uilabel(app.CrossOverFreqTab);
            app.unit2Label.Text = 'unit2';
            app.SweepRateTotalCyclesTab = uitab(app.TabGroup);
            app.SweepRateTotalCyclesTab.Title = 'Sweep Rate & Total Cycles';
            app.SweepRateListBoxLabel = uilabel(app.SweepRateTotalCyclesTab);
            app.SweepRateListBoxLabel.Text = 'Sweep Rate';
            app.SweepRateListBox = uilistbox(app.SweepRateTotalCyclesTab);
            app.SweepRateListBox.Items = {'log', 'linear'};
            app.SweepRateListBox.ValueChangedFcn = createCallbackFcn(app, @SweepRateListBoxValueChanged, true);
            app.SweepRateListBox.Value = 'log';
            app.LowerFreqHzEditField_2Label = uilabel(app.SweepRateTotalCyclesTab);
            app.LowerFreqHzEditField_2Label.Text = 'Lower Freq (Hz)';
            app.LowerFreqHzEditField_2 = uieditfield(app.SweepRateTotalCyclesTab, 'text');
            app.LowerFreqHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @LowerFreqHzEditField_2ValueChanging, true);
            app.UpperFreqHzEditField_2Label = uilabel(app.SweepRateTotalCyclesTab);
            app.UpperFreqHzEditField_2Label.Text = 'Upper Freq (Hz)';
            app.UpperFreqHzEditField_2 = uieditfield(app.SweepRateTotalCyclesTab, 'text');
            app.UpperFreqHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @UpperFreqHzEditField_2ValueChanging, true);
            app.DurationEditFieldLabel = uilabel(app.SweepRateTotalCyclesTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SweepRateTotalCyclesTab, 'text');
            app.DurationEditField.ValueChangingFcn = createCallbackFcn(app, @DurationEditFieldValueChanging, true);
            app.UnitListBoxLabel = uilabel(app.SweepRateTotalCyclesTab);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.SweepRateTotalCyclesTab);
            app.UnitListBox.Items = {'min', 'sec'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'min';
            app.CalculateRateButton = uibutton(app.SweepRateTotalCyclesTab, 'push');
            app.CalculateRateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRateButtonPushed, true);
            app.CalculateRateButton.Text = 'Calculate Rate';
            app.ResultsTextAreaLabel = uilabel(app.SweepRateTotalCyclesTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.SweepRateTotalCyclesTab);
            app.SetDurationto1octminButton = uibutton(app.SweepRateTotalCyclesTab, 'push');
            app.SetDurationto1octminButton.ButtonPushedFcn = createCallbackFcn(app, @SetDurationto1octminButtonPushed, true);
            app.SetDurationto1octminButton.Text = 'Set Duration to 1 oct/min';
            app.SineSweepTransTab = uitab(app.TabGroup);
            app.SineSweepTransTab.Title = 'Sine Sweep Trans';
            app.EditFieldLabel = uilabel(app.SineSweepTransTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.SineSweepTransTab, 'text');
            app.sine_sweep_parameters_ahver15byTomIrvineLabel = uilabel(app.SineSweepUIFigure);
            app.sine_sweep_parameters_ahver15byTomIrvineLabel.Text = 'sine_sweep_parameters_ah    ver 1.5   by Tom Irvine';
            app.TabGroup2 = uitabgroup(app.SineSweepUIFigure);
            app.CalculationTab = uitab(app.TabGroup2);
            app.CalculationTab.Title = 'Calculation';
            app.ResponseListBoxLabel = uilabel(app.CalculationTab);
            app.ResponseListBoxLabel.Text = 'Response';
            app.ResponseListBox = uilistbox(app.CalculationTab);
            app.ResponseListBox.Items = {'Accel (G)', 'Velocity (in/sec)', 'Velocity (m/sec)', 'Displacement (in)', 'Displacement (m)', 'Displacement (mm)', 'Pressure (psi)', 'Pressure (Pa)'};
            app.ResponseListBox.Value = 'Accel (G)';
            app.InputListBoxLabel = uilabel(app.CalculationTab);
            app.InputListBoxLabel.Text = 'Input';
            app.InputListBox = uilistbox(app.CalculationTab);
            app.InputListBox.Items = {'Accel (G)', 'Force (lbf)', 'Force (N)', 'Pressure (psi)', 'Pressure (Pa)'};
            app.InputListBox.Value = 'Accel (G)';
            app.CalculateButton_2 = uibutton(app.CalculationTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ReadDataButton = uibutton(app.CalculationTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.UpdateListButton = uibutton(app.CalculationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectResponseArrayListBoxLabel = uilabel(app.CalculationTab);
            app.SelectResponseArrayListBoxLabel.Text = 'Select Response Array';
            app.SelectResponseArrayListBox = uilistbox(app.CalculationTab);
            app.SelectResponseArrayListBox.Items = {};
            app.SelectResponseArrayListBox.Value = {};
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculationTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculationTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.ListboxMethod = uilistbox(app.CalculationTab);
            app.ListboxMethod.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.ListboxMethod.ValueChangedFcn = createCallbackFcn(app, @ListboxMethodValueChanged, true);
            app.ListboxMethod.Tag = 'listbox_method';
            app.ListboxMethod.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculationTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.ListboxFormat = uilistbox(app.CalculationTab);
            app.ListboxFormat.Items = {'Common array:  time(sec) & amplitude & amplitude', 'Two separate arrays, each:  time(sec) & amplitude'};
            app.ListboxFormat.ValueChangedFcn = createCallbackFcn(app, @ListboxFormatValueChanged, true);
            app.ListboxFormat.Tag = 'listbox_method';
            app.ListboxFormat.Value = 'Common array:  time(sec) & amplitude & amplitude';
            app.text1_2 = uilabel(app.CalculationTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Format';
            app.OutputFunctionsListBox_2Label = uilabel(app.CalculationTab);
            app.OutputFunctionsListBox_2Label.Text = 'Output Functions';
            app.OutputFunctionsListBox_2 = uilistbox(app.CalculationTab);
            app.OutputFunctionsListBox_2.Items = {'Transmissibility', 'Transmissibility & Phase'};
            app.OutputFunctionsListBox_2.Value = 'Transmissibility';
            app.WindowCyclesEditFieldLabel = uilabel(app.CalculationTab);
            app.WindowCyclesEditFieldLabel.Text = 'Window Cycles';
            app.WindowCyclesEditField = uieditfield(app.CalculationTab, 'text');
            app.WindowCyclesEditField.Value = '20';
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayEditFieldLabel = uilabel(app.ExportTab);
            app.OutputArrayEditFieldLabel.Text = 'Output Array';
            app.OutputArrayEditField = uieditfield(app.ExportTab, 'text');
            app.OutputArrayEditField.Value = 'sine_sweep_transmissibility';
            app.OutputFunctionsListBoxLabel = uilabel(app.ExportTab);
            app.OutputFunctionsListBoxLabel.Text = 'Output Functions';
            app.OutputFunctionsListBox = uilistbox(app.ExportTab);
            app.OutputFunctionsListBox.Items = {'Transmissibility', 'Transmissibility & Phase'};
            app.OutputFunctionsListBox.Value = 'Transmissibility';
            % Show the figure after all components are created
            app.SineSweepUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sine_sweep_parameters_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SineSweepUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SineSweepUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineSweepUIFigure)
        end
    end
end