classdef smc_s_016_psd_spl_mpe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunStatusEditField             matlab.ui.control.EditField
        RunStatusEditFieldLabel        matlab.ui.control.Label
        WindowListBox_2                matlab.ui.control.ListBox
        WindowListBox_2Label           matlab.ui.control.Label
        OutputNamesLabel               matlab.ui.control.Label
        MaximumEnvelopeEditField       matlab.ui.control.EditField
        MaximumEnvelopeEditFieldLabel  matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.EditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.EditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        MeanRemovalListBox             matlab.ui.control.ListBox
        MeanRemovalListBoxLabel        matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        InputUnitListBox               matlab.ui.control.ListBox
        InputUnitListBoxLabel          matlab.ui.control.Label
        OctaveBandListBox              matlab.ui.control.ListBox
        OctaveBandListBoxLabel         matlab.ui.control.Label
        P9550EditField                 matlab.ui.control.EditField
        P9550EditFieldLabel            matlab.ui.control.Label
        OutputMPETypeListBox           matlab.ui.control.ListBox
        OutputMPETypeListBoxLabel      matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        CalculateButton                matlab.ui.control.Button
        ReturnButton                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM     % Description
        AType   %
        iflag   %
        kflag   %
        nSignal %
    end
    
    methods (Access = private)
        
        function Signal(app)

            Items = char(app.SignalProcessingListBox.Items);
            im=app.SignalProcessingListBox.Value;
            [app.nSignal]=find_index(Items,im);

            if(app.nSignal==1)
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';        
            end
       
        end

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end         
            app.RunStatusEditField.Value='Awating Calculation';
        end

        function update(app)
            [cell_array]=update_two_columns();
            app.kflag=0;  
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end    

        function Octave(app)
            Items = char(app.OutputMPETypeListBox.Items);
            im=app.OutputMPETypeListBox.Value;
            [app.AType]=find_index(Items,im);
            if(app.AType==1)
                so{1}='1/6';
                so{2}='1/12';
                app.P9550EditField.Value='p9550_psd'; 
                app.MaximumEnvelopeEditField.Value='max_env_psd';
                ss{1}='G';
                ss{2}='m/sec^2';
                app.MinEditField.Value=20;
                app.MaxEditField.Value=2000;         
            end
            if(app.AType==2)
                so{1}='Full';
                so{2}='1/3';
                app.P9550EditField.Value='p9550_spl'; 
                app.MaximumEnvelopeEditField.Value='max_env_spl';
                ss{1}='psi';
                ss{2}='Pa';    
                ss{3}='micro Pa'; 
                app.MinEditField.Value=10;
                app.MaxEditField.Value=20000;                  
            end
            app.InputUnitListBox.Items=ss;
            app.OctaveBandListBox.Items=so;
            app.OctaveBandListBox.Value=app.OctaveBandListBox.Items{2};           
        end    

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app)
            update(app);
            Octave(app);
            Signal(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);
            
            fig_num=1; 
           
            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            app.RunStatusEditField.Value='Reading Data';
            pause(0.2);

            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            if(iflagq~=1)
	            return
            end

            pause(0.2);
            app.RunStatusEditField.Value='Time Limit Check';       
            
            if(app.nSignal==2)
                tstart=str2num(app.StartTimesecEditField.Value);
                tend=str2num(app.EndTimesecEditField.Value);  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);
            end    

            pause(0.2);
            app.RunStatusEditField.Value='Interpolation Check';

            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(app.iflag==999)
                return;
            end            

            %%%

            Items = char(app.InputUnitListBox.Items);
            im=app.InputUnitListBox.Value;
            [nunit]=find_index(Items,im);

            YS=im;

            if(app.AType==2)

                if(nunit==1)
                    ref=2.9e-09;
                end
                if(nunit==2)
                    ref=20e-06;
                end
                if(nunit==3)
                    ref= 0.0002;
                end
            end
            pause(0.2);
            app.RunStatusEditField.Value='Plotting Time History';

            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            
            xlabel('Time (sec)');
            if(app.AType==1)
                title('Acceleration Time History');
                sy=sprintf('Accel (%s)',im);
                if(nunit==1)
                    psd_label=sprintf('Accel (%s^2/Hz)',YS);
                else
                    psd_label=sprintf('Accel ((%s)^2/Hz)',YS);                    
                end
            else
                title('Acoustic Pressure Time History'); 
                sy=sprintf('Pressure (%s)',im);           
            end  
            ylabel(sy);

            %%%

            pause(0.2);
            app.RunStatusEditField.Value='Octave Setup';

            Items = char(app.OctaveBandListBox.Items);
            im=app.OctaveBandListBox.Value;
            [nnn]=find_index(Items,im);

            if(app.AType==1) % psd
                if(nnn==1)
                    ioct=3;  % 1/6
                else
                    ioct=4;  % 1/12
                end    
            end
            if(app.AType==2) % spl
                if(nnn==1)
                    ioct=1;  % full
                else
                    ioct=2;  % 1/3
                end                       
            end  

            [fl,fc,fu,imax]=octaves_alt(ioct);
            fc=fix_size(fc);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            mmr=app.MeanRemovalListBox.Value;
            
            if(strcmp(mmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            hhr=app.WindowListBox_2.Value;
            
            if(strcmp(hhr,'Hanning'))
                h_choice=2;
            else
                h_choice=1;
            end    
            fmin=app.MinEditField.Value;
            fmax=app.MaxEditField.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            pause(0.2);
            app.RunStatusEditField.Value='Time Check';

            t=app.THM(:,1);
            amp=app.THM(:,2);
            aaa=amp;

            tmin=t(1);
            tmax=t(end);

            i=1;
            
            while(1)
                
                t1=tmin+(i-1)*0.5;   % one second intervals
                t2=t1+1;
                
                if(t2>tmax)
                    break;
                end
                
                [~, ia] = min(abs(t-t1));
                [~, ib] = min(abs(t-t2));   
             
                index(i,:)=[ia ib];
                i=i+1;
                
            end    

            pause(0.2);
            app.RunStatusEditField.Value='Main Calculation';
            
            NI=length(index);
            
            y=aaa(index(1,1):index(1,2));
            
            NW=2;
            
            mmm=floor(length(y)/2);

            dt=mean(diff(t));

            df=1/(mmm*dt);

            fprintf('df=%8.4g \n',df);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            psd_table=zeros(imax,NI);
            
            rms=zeros(NI,1);

            progressbar;
            
            for i=1:NI
            
                progressbar(i/NI)
                
                y=aaa(index(i,1):index(i,2));
                amp=y;
                    
                try
                    [freq,full,~]=psd_core(mmm,NW,mr_choice,h_choice,amp,df);
                catch
                    warndlg('psd core failed');
                    return;
                end

                PSD=[freq full];
                
                ff=PSD(:,1);
                [~, j1] = min(abs(ff-fmin));
                [~, j2] = min(abs(ff-fmax));
                
                [~,rms(i)] = calculate_PSD_slopes_np(PSD(j1:j2,1),PSD(j1:j2,2));
                
                % fprintf(' %d %7.3g \n',i,rms);
                
                [Poct]=PSD_to_octave(fl,fc,fu,imax,PSD);
                
                psd_table(:,i)=Poct;
            
            end
            
            progressbar(1);
    
            psd_max=zeros(imax,1);
            psd_p9550=zeros(imax,2);
            
            psd_p9550(:,1)=fc;
            
            x=log10(psd_table);

            p=95;
            c=50;
            nsamples=NI;

            [k,~,~,~]=k_factor(p,c,nsamples);
            
            fprintf('\n ** k=%7.3g ** \n',k)

            for i=1:imax
                mu=mean(x(i,:));
                sd=std(x(i,:));
                psd_p9550(i,2)=10^(mu+k*sd);
                psd_max(i)=max(psd_table(i,:));
            end
            
            % remove_zeros
            
            for i=imax:-1:1
                if(psd_p9550(i,1)<fmin || psd_p9550(i,1)>fmax)
                    psd_p9550(i,:)=[];
                    psd_table(i,:)=[];
                    psd_max(i)=[];              
                end
            end   
          
            LL=length(psd_p9550(:,2));

            for i=LL:-1:1
                if(psd_p9550(i,2)< 1.0e-10 || psd_max(i) < 1.0e-10)
                    psd_p9550(i,:)=[];
                    psd_table(i,:)=[];
                    psd_max(i)=[];              
                end
            end       

            LL=length(psd_p9550(:,2));

            for i=1:LL
                leg{i}=sprintf('%d',i-1);
            end

            leg{1}='P95/50';

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            ff=psd_p9550(:,1);           

            if(app.AType==1)

                [~,rms] = calculate_PSD_slopes(ff,psd_p9550(:,2));
                t_string_p9550=sprintf('PSD  P95/50 MPE   %6.3g %sRMS Overall ',rms,YS);

                [~,rms] = calculate_PSD_slopes(ff,psd_max);
                t_string_max=sprintf('PSD  Maximum Envelope   %6.3g %sRMS Overall ',rms,YS);              

                xlab='Frequency (Hz)';
                ylab=psd_label;

                ppp=[ff psd_max psd_p9550(:,2) psd_table];

                t_string='Power Spectral Density';

                [fig_num]=psd_plot_all_alt(fig_num,t_string,ppp,leg,ylab);

                [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string_p9550,psd_p9550,fmin,fmax);
                [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string_max,[ff psd_max],fmin,fmax);      
            else

                dB=zeros(LL,1);
                dB_max=zeros(LL,1);

                if(ioct==1) % full
                    scale=1/sqrt(2);
                else        % 1/3   
                    scale=(2^(1/6)-1/2^(1/6));
                end  

                sz=size(psd_table);

                for i=1:LL

                    bw=ff(i)*scale;

                    rms=sqrt(psd_p9550(i,2)*bw);
                    dB(i)=20*log10(rms/ref);

                    rms=sqrt(psd_max(i)*bw);
                    dB_max(i)=20*log10(rms/ref);          

                    for j=1:sz(2)
                        rms=sqrt(psd_table(i,j)*bw);
                        psd_table(i,j)=20*log10(rms/ref);
                    end
                end

                xlab='Center Frequency (Hz)';
                ylab='SPL (dB)'; 
                data=[ff dB_max dB];
                ppp=[data psd_table];
                ng=1;    
                    
                ttt='P95/50 MPE';
                [oadb]=oaspl_function(dB);

                if(ioct==2)
                    t_string=sprintf(' One-Third Octave SPL, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                    [fig_num]=plot_one_onethird_bar_ng_alt(fig_num,xlab,ylab,[ff dB],t_string,ng);
                else
                    t_string=sprintf(' Full Octave SPL, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);             
                    [fig_num]=plot_one_one_bar_ng_alt(fig_num,xlab,ylab,[ff dB],t_string,ng);       
                end    

                ttt='Maximum Envelope';
                tstring=ttt;
                [oadb]=oaspl_function(dB_max);

                if(ioct==2)
                    t_string=sprintf(' One-Third Octave SPL, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                    [fig_num]=plot_one_onethird_bar_ng_alt(fig_num,xlab,ylab,[ff dB_max],t_string,ng);
                    n_type=1;
                    [fig_num]=spl_plot_title(fig_num,n_type,ff,dB_max,tstring);
                else
                    t_string=sprintf(' Full Octave SPL, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);             
                    [fig_num]=plot_one_one_bar_ng_alt(fig_num,xlab,ylab,[ff dB_max],t_string,ng);   
                    n_type=2;
                    [fig_num]=spl_plot_title(fig_num,n_type,ff,dB_max,tstring);
                end    
           
                %%%%%%%%%%%%%%%%

                if(ioct==2)
                    t_string=sprintf(' One-Third Octave SPL, Ref: 20 micro Pa ');
                else
                    t_string=sprintf(' Full Octave SPL, Ref: 20 micro Pa ');                   
                end    
                [fig_num]=spl_plot_all_alt(fig_num,t_string,ppp,leg);
   
            end    

            p9550_name=app.P9550EditField.Value;
            max_env_name=app.MaximumEnvelopeEditField.Value;

            if(app.AType==1)
                assignin('base',p9550_name,psd_p9550);
                assignin('base',max_env_name,[ff psd_max]);            
            else    
                assignin('base',p9550_name,[ff dB]);
                assignin('base',max_env_name,[ff dB_max]);
            end

            app.RunStatusEditField.Value='Calculation Complete';

            sss=sprintf('Arrays saved as: \n   %s\n   %s',p9550_name,max_env_name);
            msgbox(sss);

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
            app.kflag=0;
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Value changed function: OutputMPETypeListBox
        function OutputMPETypeListBoxValueChanged(app, event)
            Octave(app);
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'smc_s_016_psd_spl_mpe   ver 1.2  by Tom Irvine        '; ''; 'This script calculates the MPE of a time history per SMC-S-016, 3.26 & 3.27         The input array must have two columns: time(sec) & amplitude.'};
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.listbox_method = uilistbox(app.UIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.UIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.UIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.UIFigure);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.OutputMPETypeListBoxLabel = uilabel(app.UIFigure);
            app.OutputMPETypeListBoxLabel.Text = 'Output MPE Type';
            app.OutputMPETypeListBox = uilistbox(app.UIFigure);
            app.OutputMPETypeListBox.Items = {'PSD', 'SPL'};
            app.OutputMPETypeListBox.ValueChangedFcn = createCallbackFcn(app, @OutputMPETypeListBoxValueChanged, true);
            app.OutputMPETypeListBox.Value = 'PSD';
            app.P9550EditFieldLabel = uilabel(app.UIFigure);
            app.P9550EditFieldLabel.Text = 'P95/50';
            app.P9550EditField = uieditfield(app.UIFigure, 'text');
            app.OctaveBandListBoxLabel = uilabel(app.UIFigure);
            app.OctaveBandListBoxLabel.Text = 'Octave Band';
            app.OctaveBandListBox = uilistbox(app.UIFigure);
            app.OctaveBandListBox.Items = {'Full', 'One-Third', 'One-Sixth', 'One-Twelfth'};
            app.OctaveBandListBox.Value = 'Full';
            app.InputUnitListBoxLabel = uilabel(app.UIFigure);
            app.InputUnitListBoxLabel.Text = 'Input Unit';
            app.InputUnitListBox = uilistbox(app.UIFigure);
            app.InputUnitListBox.Items = {'G', 'm/sec^2'};
            app.InputUnitListBox.Value = 'G';
            app.SignalProcessingListBoxLabel = uilabel(app.UIFigure);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.UIFigure);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.MeanRemovalListBoxLabel = uilabel(app.UIFigure);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.UIFigure);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.PlotFrequenciesHzLabel = uilabel(app.UIFigure);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.Value = 2000;
            app.StartTimesecEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.UIFigure, 'text');
            app.EndTimesecEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.UIFigure, 'text');
            app.MaximumEnvelopeEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumEnvelopeEditFieldLabel.Text = 'Maximum Envelope';
            app.MaximumEnvelopeEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNamesLabel = uilabel(app.UIFigure);
            app.OutputNamesLabel.Text = 'Output Names';
            app.WindowListBox_2Label = uilabel(app.UIFigure);
            app.WindowListBox_2Label.Text = 'Window';
            app.WindowListBox_2 = uilistbox(app.UIFigure);
            app.WindowListBox_2.Items = {'Hanning', 'Rectangular'};
            app.WindowListBox_2.Value = 'Hanning';
            app.RunStatusEditFieldLabel = uilabel(app.UIFigure);
            app.RunStatusEditFieldLabel.Text = 'Run Status';
            app.RunStatusEditField = uieditfield(app.UIFigure, 'text');
            app.RunStatusEditField.Editable = 'off';
            app.RunStatusEditField.Value = 'Awaiting Calculation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smc_s_016_psd_spl_mpe

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end