classdef sound_editor_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundEditorUIFigure             matlab.ui.Figure
        Theexportedfilewillhaveasamplerateof44100Label  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ReadAudioFileTab                matlab.ui.container.Tab
        SupportedFormatswavoggflacauaifaifcaiffmp3m4amp4Label  matlab.ui.control.Label
        ExportSoundFiletoMatlabWorkspacePanel  matlab.ui.container.Panel
        ch2Label                        matlab.ui.control.Label
        ch1Label                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditField_ch2                   matlab.ui.control.EditField
        EditField_ch1                   matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        CalcuateFFTButton               matlab.ui.control.Button
        SaveasASCIITextButton           matlab.ui.control.Button
        PlaySoundButton                 matlab.ui.control.Button
        SelectAudioFileButton           matlab.ui.control.Button
        ReadConvertASCIITextFileTab     matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        GenerateButton                  matlab.ui.control.Button
        SaveFileButton_2                matlab.ui.control.Button
        PlaySoundButton_3               matlab.ui.control.Button
        GenerateSoundTab                matlab.ui.container.Tab
        FadeInOutPercentEditField       matlab.ui.control.EditField
        FadeInOutPercentEditFieldLabel  matlab.ui.control.Label
        GenerateSoundButton             matlab.ui.control.Button
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        SampleRateHzListBox             matlab.ui.control.ListBox
        SampleRateHzListBoxLabel        matlab.ui.control.Label
        TabGroup_Generate               matlab.ui.container.TabGroup
        SineToneTab                     matlab.ui.container.Tab
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        BeatEffectTab                   matlab.ui.container.Tab
        Amp2EditField                   matlab.ui.control.EditField
        AmpEditField_2Label             matlab.ui.control.Label
        FreqHz2EditField                matlab.ui.control.EditField
        FreqHzEditField_2Label          matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Amp1EditField                   matlab.ui.control.EditField
        AmpEditFieldLabel               matlab.ui.control.Label
        FreqHz1EditField                matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        ShepardToneTab                  matlab.ui.container.Tab
        NumberofTonesListBox            matlab.ui.control.ListBox
        NumberofTonesListBoxLabel       matlab.ui.control.Label
        SaveFileButton                  matlab.ui.control.Button
        PlaySoundButton_2               matlab.ui.control.Button
        sound_editor_ahver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Fs           %   
        signal       % 
        channel_one  %
        channel_two  %
        channel_num  %
        tt           %  
        stitle       %
        THM          %
        kflag        %
    end
    
    methods (Access = private)
        
       function update(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)

                if( contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list') ) 
                    cell_array{i} ='999';
                else  

                    try
               
                        TTT=evalin('base',vars(i).name);
                        sz=size(TTT);

                        if(sz(2)==2)
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                    
                end    
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'999'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end

            try
                for i=length(vars):-1:1
                    if(contains(cell_array{i},'myHandle'))
                        cell_array(i,:)=[];
                    end
                end
            catch
            end    

            app.kflag=0;  

            app.SelectInputArrayListBox.Items=unique(sort(cell_array));  

        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end
        end
        
        function Shepard(app)
            dur=str2num(app.DurationsecEditField.Value);
            number_tones=str2num(app.NumberofTonesListBox.Value);
            
            f1 = 27.5;                % starting freq (Hz)
            f2 = (2^number_tones)*f1; % ending freq (Hz)
            %
            %   log sweep rate
            %
            dt=1/app.Fs;    % time step
            ns=dur*app.Fs;  % number of sample/cycles
            cycles=1;    % number of cycles
            %  
            oct=log(f2/f1)/log(2.);   % number of octaves   
            %
            ntimes = ns*cycles;
            %
            tpi=2.*pi;
            %
            rate=oct/dur;
            %
            disp(' Calculating time vector... ')
            TT=linspace(dt,(ntimes+1)*dt,ntimes); 
            %  
            %			fspectral = f1*pow(2.,rate*t);
            %
            a=zeros(1,ntimes);
            arg=zeros(number_tones,1);
            key=zeros(number_tones,1);
            noc=zeros(number_tones,1);
            amp=zeros(number_tones,1);
            %
            for i=1:number_tones
                noc(i)=2^(i-1);
            end
            %
            fspan=f2-f1;
            theta_coefficient=tpi/(rate*log(2));
            %
            progressbar % Create figure and set starting time
            for i=1:ntimes
            %
                progressbar(i/ntimes); % Update figure  
            %
                W=-1.+2^(rate*TT(i));
            %
                arg(1)=f1*W;
            %
                for j=1:number_tones
                    if(key(j)==0)
                        arg(j)=arg(1)*noc(j);       
                    else
                        W=-1.+2^(rate*TT(i-key(j)));
                        arg(j)=f1*W;    
                     end
                end
            %
                for j=1:number_tones
                    fspectral=noc(j)*f1*(2^(rate*TT(i-key(j))));
                    delta=(fspectral-f1)/fspan;
                    amp(j)=(1-cos(tpi*delta));
            %
                    if(fspectral>f2)
                         key(j)=i;
                         noc(j)=1;
                    end
                end
            %
                theta=theta_coefficient*arg;
            %
                a(i)=sum(amp.*sin(theta));
            %
            end
            progressbar(1);
            %
            a(1)=0.;
            clear aa;
            aa=detrend(a);

            app.signal=aa';
            app.signal = app.signal/max(abs(app.signal));
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlaySoundButton.Enable='off';
            app.SaveasASCIITextButton.Enable='off';
            app.CalcuateFFTButton.Enable='off'; 

            app.EditField_ch1.Visible='off';
            app.EditField_ch2.Visible='off';  
            app.ch1Label.Visible='off';
            app.ch2Label.Visible='off';  
            app.SaveButton.Enable='off';

            app.PlaySoundButton_2.Enable='off';
            app.SaveFileButton.Enable='off';        

            ClosePlots(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SelectAudioFileButton
        function SelectAudioFileButtonPushed(app, event)
            [filename, pathname]=...
            uigetfile({'*.wav;*.aifc;*.aiff;*.mp3;*.m4a;*.mp4;*.ogg;*.flac;*.au;*.aif',...
                                     'All Image Files';'*.*','All Files'},'File Selector');      
                  
            filename = fullfile(pathname, filename);
            
            [app.signal, app.Fs] = audioread(filename);
            
            out1=sprintf(' Fs = %10.5g samples/sec ',app.Fs);
            app.Label.Text=out1;

            app.signal=fix_size(app.signal);
            
            sz=size(app.signal);
            
            N=length(app.signal(:,1));
            dt=1/app.Fs;
            t=linspace(0,(N-1)*dt,N);
            t=fix_size(t);
            
            app.stitle=sprintf('%s',filename);
            
            figure(1);
            if(sz(2)==1)
                plot(t,app.signal(:,1)); 
            else
                plot(t,app.signal(:,1),t,app.signal(:,2));    
            end    
            xlabel('Time (sec)');
            ylabel('Amplitude');
            title(app.stitle,'interpreter','none');
            grid on;
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.EditField_ch1.Visible='on';
            app.ch1Label.Visible='on';

            app.EditField_ch2.Visible='off';
            app.ch2Label.Visible='off';            
            
            if(sz(2)==2)
                app.EditField_ch2.Visible='on';
                app.ch2Label.Visible='on';
            end
            
            app.channel_one=[t app.signal(:,1)]; 

            if(sz(2)==1)
                app.channel_num=1;    
            else   
                app.channel_num=2;      
                app.channel_two=[t app.signal(:,2)]; 
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.SaveButton.Enable='on';

            app.PlaySoundButton.Enable='on';
            app.SaveasASCIITextButton.Enable='on';
           
        end

        % Button pushed function: CalcuateFFTButton
        function CalcuateFFTButtonPushed(app, event)
            vibrationdata_fourier_transform_ah; 
        end

        % Button pushed function: SaveasASCIITextButton
        function SaveasASCIITextButtonPushed(app, event)
            N=app.channel_num;
            ch1=app.channel_one;
            
            filename1 = uiputfile('*.txt','Save Channel 1');
            save(filename1,'ch1','-ASCII') 
            
            if(N==2)
                ch2=app.channel_two;
                filename2 = uiputfile('*.txt','Save Channel 2');
                save(filename2,'ch2','-ASCII')     
            end           
            
            app.CalcuateFFTButton.Enable='on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
               
            ch1=app.channel_one;
            
            name1=app.EditField_ch1.Value;
            assignin('base',name1,ch1);
            
            if(app.channel_num==2)
                ch2=app.channel_two;
                name2=app.EditField_ch2.Value;
                assignin('base',name2,ch2);    
            end

            app.CalcuateFFTButton.Enable='on';
            msgbox('Save Complete');         
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            try    
                player = audioplayer(app.signal, app.Fs);
                play(player)
                pause(max(size(app.signal))/app.Fs);
            catch
                warndlg('Play Sound Failed')
            end
        end

        % Button pushed function: PlaySoundButton_2
        function PlaySoundButton_2Pushed(app, event)
            PlaySoundButtonPushed(app, event);
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            filename = uiputfile('*.wav');
            audiowrite(filename,app.signal,app.Fs)
        end

        % Button pushed function: GenerateSoundButton
        function GenerateSoundButtonPushed(app, event)
            clear app.signal;
            clear app.tt;

            app.Fs=str2num(app.SampleRateHzListBox.Value);

            try
                n=app.TabGroup_Generate.SelectedTab;
                dt=1/app.Fs;
                dur=str2num(app.DurationsecEditField.Value);
                N=ceil(dur/dt);
                app.tt=linspace(0,(N-1)*dt,N);          
            catch
                warndlg('Data Retrieved Failed');
                return;
            end
            
            if(contains(n.Title,'Sine Tone'))
                try
                    f=str2num(app.FrequencyHzEditField.Value);
                    omega=2*pi*f;
                    app.signal=0.15*sin(omega*app.tt);
                    app.stitle='Sine Tone';
                catch
                    warndlg('Sine Tone failed');
                    return;
                end
            end    
            if(contains(n.Title,'Beat'))
                A1=str2num(app.Amp1EditField.Value);
                A2=str2num(app.Amp2EditField.Value);
                f1=str2num(app.FreqHz1EditField.Value);
                f2=str2num(app.FreqHz2EditField.Value);
                omega1=2*pi*f1;
                omega2=2*pi*f2;
                app.signal(1:N)=0.15*(A1*sin(omega1*app.tt)+A2*sin(omega2*app.tt));
            end     
            if(contains(n.Title,'Shepard'))
                Shepard(app);
            end     

            per=str2num(app.FadeInOutPercentEditField.Value);
            [app.signal]=half_cosine_fade_perc(app.signal,per);

            try
                app.tt=fix_size(app.tt);
                app.signal=fix_size(app.signal);

                size(app.tt)
                size(app.signal)
                
                figure(1);
                plot(app.tt,app.signal);
                xlabel('Time (sec)');
                ylabel('Amplitude');
                title(app.stitle);
                grid on;
            catch
                warndlg('Plot failed');
                return;
            end

            app.PlaySoundButton_2.Enable='on';
            app.SaveFileButton.Enable='on';   

        end

        % Button pushed function: PlaySoundButton_3
        function PlaySoundButton_3Pushed(app, event)
            PlaySoundButtonPushed(app, event);           
        end

        % Button pushed function: SaveFileButton_2
        function SaveFileButton_2Pushed(app, event)
            SaveFileButtonPushed(app, event);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

            if(app.kflag==1)
                warndlg('No arrays available');
                return;
            end    

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                     app.stitle=sprintf('%s',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                        
            sz=size(app.THM);
            
            if(sz(2)>sz(1))
                app.THM=app.THM';
            end
            
            amplitude=detrend(app.THM(:,2));
            
            maxa=max(abs(amplitude));
            
            app.signal=amplitude/maxa;
            
            N=length(app.signal);
            
            dur=(app.THM(N,1)-app.THM(1,1));
            
            app.Fs=round((N-1)/dur);
            
            if(app.Fs==44100)
                xi=app.THM(:,1);
            else
                app.Fs=44100;
                dt=1/app.Fs;
                N=round(dur/dt);
            %
            %  This method assumes that the sample rate is already uniform
            %
            %  Linear interpolation
            %
                xi =linspace(0,(N-1)*dt,N)+app.THM(1,1);  
                app.signal = interp1(app.THM(:,1),app.signal,xi);    
            %
            end
            
            figure(1);
            plot(xi,app.signal);
            xlabel('Time (sec)');
            ylabel('Amplitude');
            title(app.stitle,'interpreter','none');
            grid on;

            app.PlaySoundButton_3.Enable='on';
            app.SaveFileButton_2.Enable='on';

        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   app.stitle=sprintf('%s',filename);
                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);            
        end

        % Button down function: ReadConvertASCIITextFileTab
        function ReadConvertASCIITextFileTabButtonDown(app, event)
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundEditorUIFigure = uifigure('Visible', 'off');
            app.SoundEditorUIFigure.Name = 'Sound Editor';
            app.sound_editor_ahver12byTomIrvineLabel = uilabel(app.SoundEditorUIFigure);
            app.sound_editor_ahver12byTomIrvineLabel.Text = ' sound_editor_ah    ver 1.2   by Tom Irvine';
            app.TabGroup = uitabgroup(app.SoundEditorUIFigure);
            app.ReadAudioFileTab = uitab(app.TabGroup);
            app.ReadAudioFileTab.Title = 'Read Audio File';
            app.SelectAudioFileButton = uibutton(app.ReadAudioFileTab, 'push');
            app.SelectAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioFileButtonPushed, true);
            app.SelectAudioFileButton.Text = 'Select Audio File';
            app.PlaySoundButton = uibutton(app.ReadAudioFileTab, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.SaveasASCIITextButton = uibutton(app.ReadAudioFileTab, 'push');
            app.SaveasASCIITextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasASCIITextButtonPushed, true);
            app.SaveasASCIITextButton.Text = 'Save as ASCII Text';
            app.CalcuateFFTButton = uibutton(app.ReadAudioFileTab, 'push');
            app.CalcuateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalcuateFFTButtonPushed, true);
            app.CalcuateFFTButton.Text = 'Calcuate FFT';
            app.ExportSoundFiletoMatlabWorkspacePanel = uipanel(app.ReadAudioFileTab);
            app.ExportSoundFiletoMatlabWorkspacePanel.Title = 'Export Sound File to Matlab Workspace';
            app.SaveButton = uibutton(app.ExportSoundFiletoMatlabWorkspacePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField_ch1 = uieditfield(app.ExportSoundFiletoMatlabWorkspacePanel, 'text');
            app.EditField_ch2 = uieditfield(app.ExportSoundFiletoMatlabWorkspacePanel, 'text');
            app.Label = uilabel(app.ExportSoundFiletoMatlabWorkspacePanel);
            app.ch1Label = uilabel(app.ExportSoundFiletoMatlabWorkspacePanel);
            app.ch1Label.Text = 'ch 1';
            app.ch2Label = uilabel(app.ExportSoundFiletoMatlabWorkspacePanel);
            app.ch2Label.Text = 'ch 2';
            app.SupportedFormatswavoggflacauaifaifcaiffmp3m4amp4Label = uilabel(app.ReadAudioFileTab);
            app.SupportedFormatswavoggflacauaifaifcaiffmp3m4amp4Label.Text = 'Supported Formats:  .wav,  .ogg,  .flac,  .au,  .aif,  .aifc,  .aiff,  .mp3,  .m4a,  .mp4';
            app.ReadConvertASCIITextFileTab = uitab(app.TabGroup);
            app.ReadConvertASCIITextFileTab.Title = 'Read & Convert ASCII Text File';
            app.ReadConvertASCIITextFileTab.ButtonDownFcn = createCallbackFcn(app, @ReadConvertASCIITextFileTabButtonDown, true);
            app.PlaySoundButton_3 = uibutton(app.ReadConvertASCIITextFileTab, 'push');
            app.PlaySoundButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButton_3Pushed, true);
            app.PlaySoundButton_3.Text = 'Play Sound';
            app.SaveFileButton_2 = uibutton(app.ReadConvertASCIITextFileTab, 'push');
            app.SaveFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButton_2Pushed, true);
            app.SaveFileButton_2.Text = 'Save File';
            app.GenerateButton = uibutton(app.ReadConvertASCIITextFileTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.listbox_method = uilistbox(app.ReadConvertASCIITextFileTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ReadConvertASCIITextFileTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ReadConvertASCIITextFileTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ReadConvertASCIITextFileTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ReadConvertASCIITextFileTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.GenerateSoundTab = uitab(app.TabGroup);
            app.GenerateSoundTab.Title = 'Generate Sound';
            app.PlaySoundButton_2 = uibutton(app.GenerateSoundTab, 'push');
            app.PlaySoundButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButton_2Pushed, true);
            app.PlaySoundButton_2.Text = 'Play Sound';
            app.SaveFileButton = uibutton(app.GenerateSoundTab, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.TabGroup_Generate = uitabgroup(app.GenerateSoundTab);
            app.SineToneTab = uitab(app.TabGroup_Generate);
            app.SineToneTab.Title = 'Sine Tone';
            app.FrequencyHzEditFieldLabel = uilabel(app.SineToneTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineToneTab, 'text');
            app.BeatEffectTab = uitab(app.TabGroup_Generate);
            app.BeatEffectTab.Title = 'Beat Effect';
            app.FreqHzEditFieldLabel = uilabel(app.BeatEffectTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHz1EditField = uieditfield(app.BeatEffectTab, 'text');
            app.AmpEditFieldLabel = uilabel(app.BeatEffectTab);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.Amp1EditField = uieditfield(app.BeatEffectTab, 'text');
            app.Amp1EditField.Value = '1';
            app.Label_2 = uilabel(app.BeatEffectTab);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.BeatEffectTab);
            app.Label_3.Text = '2';
            app.FreqHzEditField_2Label = uilabel(app.BeatEffectTab);
            app.FreqHzEditField_2Label.Text = 'Freq (Hz)';
            app.FreqHz2EditField = uieditfield(app.BeatEffectTab, 'text');
            app.AmpEditField_2Label = uilabel(app.BeatEffectTab);
            app.AmpEditField_2Label.Text = 'Amp';
            app.Amp2EditField = uieditfield(app.BeatEffectTab, 'text');
            app.Amp2EditField.Value = '1';
            app.ShepardToneTab = uitab(app.TabGroup_Generate);
            app.ShepardToneTab.Title = 'Shepard Tone';
            app.NumberofTonesListBoxLabel = uilabel(app.ShepardToneTab);
            app.NumberofTonesListBoxLabel.Text = 'Number of Tones';
            app.NumberofTonesListBox = uilistbox(app.ShepardToneTab);
            app.NumberofTonesListBox.Items = {'2', '3', '4', '5'};
            app.NumberofTonesListBox.Value = '2';
            app.SampleRateHzListBoxLabel = uilabel(app.GenerateSoundTab);
            app.SampleRateHzListBoxLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzListBox = uilistbox(app.GenerateSoundTab);
            app.SampleRateHzListBox.Items = {'22050', '44100'};
            app.SampleRateHzListBox.Value = '44100';
            app.DurationsecEditFieldLabel = uilabel(app.GenerateSoundTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.GenerateSoundTab, 'text');
            app.GenerateSoundButton = uibutton(app.GenerateSoundTab, 'push');
            app.GenerateSoundButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSoundButtonPushed, true);
            app.GenerateSoundButton.Text = 'Generate Sound';
            app.FadeInOutPercentEditFieldLabel = uilabel(app.GenerateSoundTab);
            app.FadeInOutPercentEditFieldLabel.Text = 'Fade In & Out Percent';
            app.FadeInOutPercentEditField = uieditfield(app.GenerateSoundTab, 'text');
            app.FadeInOutPercentEditField.Value = '5';
            app.ReturnButton = uibutton(app.SoundEditorUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Theexportedfilewillhaveasamplerateof44100Label = uilabel(app.SoundEditorUIFigure);
            app.Theexportedfilewillhaveasamplerateof44100Label.Text = 'The exported file will have a sample rate of 44100.';
            % Show the figure after all components are created
            app.SoundEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_editor_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SoundEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SoundEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundEditorUIFigure)
        end
    end
end