classdef sound_pressure_intensity_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultsTextArea             matlab.ui.control.TextArea
        ResultsTextAreaLabel        matlab.ui.control.Label
        Rayl1PasecmLabel            matlab.ui.control.Label
        CharacteristicImpedanceRaylsEditField  matlab.ui.control.EditField
        CharacteristicImpedanceRaylsEditFieldLabel  matlab.ui.control.Label
        RadiusUnitListBox           matlab.ui.control.ListBox
        RadiusUnitListBoxLabel      matlab.ui.control.Label
        PowerUnitListBox            matlab.ui.control.ListBox
        PowerUnitListBoxLabel       matlab.ui.control.Label
        RadiusEditField             matlab.ui.control.EditField
        RadiusEditFieldLabel        matlab.ui.control.Label
        PowerEditField              matlab.ui.control.EditField
        PowerEditFieldLabel         matlab.ui.control.Label
        ReturnButton                matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        VolumeListBox               matlab.ui.control.ListBox
        VolumeListBoxLabel          matlab.ui.control.Label
        InputVariablesListBox       matlab.ui.control.ListBox
        InputVariablesListBoxLabel  matlab.ui.control.Label
        sound_pressure_intensity_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function ClearResults(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClearResults(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: InputVariablesListBox
        function InputVariablesListBoxValueChanged(app, event)
            ClearResults(app);
  
            Items = strtrim(app.InputVariablesListBox.Items);
            im = strtrim(app.InputVariablesListBox.Value);
            n = find(strcmp(Items, im));


            if(n==1)
                sss='Power';
                suu{1}='dB (ref 1 pico W)';
                suu{2}='W';   
            end
            if(n==2)
                sss='Intensity';
                suu{1}='dB (ref 1 pico W/m^2)';
                suu{2}='W/m^2';   
            end
            if(n==3)
                sss='Pressure';
                suu{1}='dB (ref 20 micro Pa)';
                suu{2}='Pa';
                suu{3}='psi';      
            end
            
            ttt=sprintf('%s Unit',sss);
            
            app.PowerEditFieldLabel.Text=sss;
            app.PowerUnitListBox.Items=suu;
            app.PowerUnitListBoxLabel.Text=ttt;

        end

        % Value changed function: VolumeListBox
        function VolumeListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: PowerUnitListBox
        function PowerUnitListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: RadiusUnitListBox
        function RadiusUnitListBoxValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            ClearResults(app);
        end

        % Value changed function: CharacteristicImpedanceRaylsEditField
        function CharacteristicImpedanceRaylsEditFieldValueChanged(app, event)
            ClearResults(app);
        end

        % Value changing function: PowerEditField
        function PowerEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: RadiusEditField
        function RadiusEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Value changing function: CharacteristicImpedanceRaylsEditField
        function CharacteristicImpedanceRaylsEditFieldValueChanging(app, event)
            ClearResults(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClearResults(app);     


            Items = strtrim(app.InputVariablesListBox.Items);
            im = strtrim(app.InputVariablesListBox.Value);
            na = find(strcmp(Items, im));

            Items = strtrim(app.VolumeListBox.Items);
            im = strtrim(app.VolumeListBox.Value);
            nv = find(strcmp(Items, im));

            Items = strtrim(app.RadiusUnitListBox.Items);
            im = strtrim(app.RadiusUnitListBox.Value);
            ru = find(strcmp(Items, im));

            Items = strtrim(app.PowerUnitListBox.Items);
            im = strtrim(app.PowerUnitListBox.Value);
            iu = find(strcmp(Items, im));


            amp=str2double(app.PowerEditField.Value);
            r=str2double(app.RadiusEditField.Value);
            
            rhoc=str2double(app.CharacteristicImpedanceRaylsEditField.Value);
            
            m_per_ft = 0.3048;
            m_per_inch=0.0254;
            Pa_per_psi=6894.76;

            pressure_ref=20e-06;
            power_ref=1.0e-12;
            intensity_ref=1.0e-12;
            
            if(na==1) % power
                if(iu==1)
                    power_dB=amp;
                    power_w=power_ref*10^(amp/10);
                else
                    power_w=amp;
                    power_dB=10*log10(power_w/power_ref);        
                end
            end
            if(na==2) % intensity
                if(iu==1)
                    intensity_dB=amp;
                    intensity_wpm2=intensity_ref*10^(amp/10);
                else
                    intensity_wpm2=amp;
                    intensity_dB=10*log10(intensity_wpm2/intensity_ref);        
                end
            end
            if(na==3) % pressure
                if(iu==1)
                    pressure_dB=amp;
                    pressure_Pa=pressure_ref*10^(amp/20);
                    pressure_psi=pressure_Pa/Pa_per_psi;
                end
                if(iu==2)
                    pressure_Pa=amp;
                    pressure_dB=20*log10(pressure_Pa/pressure_ref); 
                    pressure_psi=pressure_Pa/Pa_per_psi;
                end
                if(iu==3)
                    pressure_Pa=amp*Pa_per_psi;
                    pressure_dB=20*log10(pressure_Pa/pressure_ref);
                    pressure_psi=amp;
                end    
            end
            na
            iu

            if(ru==2)
                r=r*m_per_ft;
            end
            if(ru==3)
                r=r*m_per_inch;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(nv==1)  % sphere
                area=4*pi*r^2;
            else       % hemisphere
                area=2*pi*r^2;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(na==1) % power
                intensity_wpm2=power_w/area;
                intensity_dB=10*log10(intensity_wpm2/intensity_ref);
                 
                pressure_Pa=sqrt(intensity_wpm2*rhoc);
                pressure_dB=20*log10(pressure_Pa/pressure_ref);
                pressure_psi=pressure_Pa/Pa_per_psi;
            end
            if(na==2) % intensity
                power_w=intensity_wpm2*area;
                power_dB=10*log10(power_w/power_ref);
                 
                pressure_Pa=sqrt(intensity_wpm2*rhoc);
                pressure_psi=pressure_Pa/Pa_per_psi;
                pressure_dB=20*log10(pressure_Pa/pressure_ref);    
            end
            if(na==3) % pressure
                intensity_wpm2=pressure_Pa^2/rhoc;
                intensity_dB=10*log10(intensity_wpm2/intensity_ref);
                
                power_w=intensity_wpm2*area;
                power_dB=10*log10(power_w/power_ref); 

            end
            
            ss1=sprintf(' Power  \n  %8.4g dB ref 1 pico W \n  %8.4g W \n',power_dB,power_w);
            ss2=sprintf(' Intensity  \n  %8.4g dB ref 1 pico W/m^2 \n  %8.4g W/m^2 \n',intensity_dB,intensity_wpm2);
            ss3=sprintf(' Pressure \n  %8.4g dB ref 20 micro Pa \n  %8.4g Pa rms \n  %8.4g psi rms \n',pressure_dB,pressure_Pa,pressure_psi);            
            
            sss=sprintf(' %s \n %s \n %s ',ss1,ss2,ss3);
            
            app.ResultsTextArea.Value=sss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sound_pressure_intensity_aver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.sound_pressure_intensity_aver10byTomIrvineLabel.Text = 'sound_pressure_intensity_a    ver 1.0  by Tom Irvine    Assume uniform radiation in air, free-field.';
            app.InputVariablesListBoxLabel = uilabel(app.UIFigure);
            app.InputVariablesListBoxLabel.Text = 'Input Variables';
            app.InputVariablesListBox = uilistbox(app.UIFigure);
            app.InputVariablesListBox.Items = {'Power', 'Intensity', 'Pressure rms'};
            app.InputVariablesListBox.ValueChangedFcn = createCallbackFcn(app, @InputVariablesListBoxValueChanged, true);
            app.InputVariablesListBox.Value = 'Power';
            app.VolumeListBoxLabel = uilabel(app.UIFigure);
            app.VolumeListBoxLabel.Text = 'Volume';
            app.VolumeListBox = uilistbox(app.UIFigure);
            app.VolumeListBox.Items = {'Sphere', 'Hemisphere'};
            app.VolumeListBox.ValueChangedFcn = createCallbackFcn(app, @VolumeListBoxValueChanged, true);
            app.VolumeListBox.Value = 'Sphere';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'text');
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.PowerEditField.ValueChangingFcn = createCallbackFcn(app, @PowerEditFieldValueChanging, true);
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'text');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.ValueChangingFcn = createCallbackFcn(app, @RadiusEditFieldValueChanging, true);
            app.PowerUnitListBoxLabel = uilabel(app.UIFigure);
            app.PowerUnitListBoxLabel.Text = 'Power Unit';
            app.PowerUnitListBox = uilistbox(app.UIFigure);
            app.PowerUnitListBox.Items = {'dB (ref 1 pico W)', 'W'};
            app.PowerUnitListBox.ValueChangedFcn = createCallbackFcn(app, @PowerUnitListBoxValueChanged, true);
            app.PowerUnitListBox.Value = 'dB (ref 1 pico W)';
            app.RadiusUnitListBoxLabel = uilabel(app.UIFigure);
            app.RadiusUnitListBoxLabel.Text = 'Radius Unit';
            app.RadiusUnitListBox = uilistbox(app.UIFigure);
            app.RadiusUnitListBox.Items = {'meter', 'foot', 'inch'};
            app.RadiusUnitListBox.ValueChangedFcn = createCallbackFcn(app, @RadiusUnitListBoxValueChanged, true);
            app.RadiusUnitListBox.Value = 'meter';
            app.CharacteristicImpedanceRaylsEditFieldLabel = uilabel(app.UIFigure);
            app.CharacteristicImpedanceRaylsEditFieldLabel.Text = {'Characteristic '; 'Impedance '; '(Rayls)'};
            app.CharacteristicImpedanceRaylsEditField = uieditfield(app.UIFigure, 'text');
            app.CharacteristicImpedanceRaylsEditField.ValueChangedFcn = createCallbackFcn(app, @CharacteristicImpedanceRaylsEditFieldValueChanged, true);
            app.CharacteristicImpedanceRaylsEditField.ValueChangingFcn = createCallbackFcn(app, @CharacteristicImpedanceRaylsEditFieldValueChanging, true);
            app.CharacteristicImpedanceRaylsEditField.Value = '415';
            app.Rayl1PasecmLabel = uilabel(app.UIFigure);
            app.Rayl1PasecmLabel.Text = '1 Rayl = 1 Pa sec/m';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_pressure_intensity_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end