classdef sound_pressure_reverberant_room < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        TREditField                    matlab.ui.control.EditField
        UniformReverberantTimeseciftheInputArrayonlyhastwocolumnsLabel  matlab.ui.control.Label
        sound_pressure_reverberant_roomver10byTomIrvineLabel_2  matlab.ui.control.Label
        InputArrayNameEditField        matlab.ui.control.EditField
        InputArrayNameEditFieldLabel   matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        RoomVolumeft3EditField         matlab.ui.control.EditField
        RoomVolumeft3EditFieldLabel    matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        SpeedofSoundftsecEditField     matlab.ui.control.EditField
        SpeedofSoundftsecLabel         matlab.ui.control.Label
        GasDensitylbmft3EditField      matlab.ui.control.EditField
        GasDensitylbmin3Label          matlab.ui.control.Label
        GasListBox                     matlab.ui.control.ListBox
        GasListBoxLabel                matlab.ui.control.Label
        ViewEquationButton             matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        sound_pressure_reverberant_roomver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        iunit % Description
    end
    
    methods (Access = private)
        
        function gas(app)
            Items = char(app.GasListBox.Items);
            im=app.GasListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                if(app.iunit==1)
                    rho=0.076487;
                    c=1125;
                    app.GasDensitylbmin3Label.Text='Gas Density (lbm/ft^3)';
                    app.SpeedofSoundftsecLabel.Text='Speed of Sound (ft/sec)';
                else    
                    rho=1.225;
                    c=343;        
                    app.GasDensitylbmin3Label.Text='Gas Density (kg/m^3)'; 
                    app.SpeedofSoundftsecLabel.Text='Speed of Sound (m/sec)';                
                end

                sc=sprintf('%8.4g',c);
                sr=sprintf('%8.4g',rho);               

                app.SpeedofSoundftsecEditField.Value=sc;
                app.GasDensitylbmft3EditField.Value=sr;               
            end    

            
        end
        
        function change(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iunit]=find_index(Items,im);

            if(app.iunit==1)
                app.RoomVolumeft3EditFieldLabel.Text='Room Volume (ft^3)';
            else
                app.RoomVolumeft3EditFieldLabel.Text='Room Volume (m^3)';            
            end

            gas(app);
        end
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);
            ClosePlots(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: GasListBox
        function GasListBoxValueChanged(app, event)
            gas(app);
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            change(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            ClosePlots(app);

            fprintf('\n * * * * * * * * \n\n');

            FS = app.InputArrayNameEditField.Value;
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end        

            sz=size(THM);

            num=sz(1);

            if(sz(2)==2)
                r=str2num(app.TREditField.Value);
                TR=r*ones(num,1);
            end    
            
            c=str2num(app.SpeedofSoundftsecEditField.Value);
            rho=str2num(app.GasDensitylbmft3EditField.Value);
            V=str2num(app.RoomVolumeft3EditField.Value);

            if(app.iunit==1)
                c=c*0.3048;
                rho=rho*16.0185;
                V=V*0.0283168;
            end    

            rhoc2=rho*c^2;

            Wref=1e-12;
            Pref=20e-06;

            pressure=zeros(num,1);
            pressure_dB=zeros(num,1);

            dB=THM(:,2);

            for i=1:num
                W=Wref*10^(dB(i)/10);
                pressure(i)=W*rhoc2*TR(i)/(4.4*pi*V);
                pressure_dB(i)=20*log10(pressure(i)/Pref);
            end

            noct=log2(THM(end,1)/THM(1,1));

            ratio=(num-1)/noct;

            n_type=0;

            if(ratio>0.9 && ratio<1.1)
                n_type=2;
                nt='One-Octave';
            else
                if(ratio> 2.5 && ratio<3.5)
                    n_type=1;
                    nt='One-Third Octave';
                end    
            end   

            if(n_type==0)
                warndlg('Input array must be one-octave or one-third octave format');
                return;
            end

            fig_num=1;

            f=THM(:,1);          

            overall_dB=10*log10(sum(10.^(dB/10)));
            tstring=sprintf('%s Sound Power Level  %4.1f dB Overall \n Ref: 1 pico W',nt,overall_dB);
            ylab='Power Level (dB)';
            [fig_num]=plot_bar(fig_num,f,dB,tstring,ylab);

            
            overall_dB=10*log10(sum(10.^(pressure_dB/10)));
            tstring=sprintf('%s Sound Pressure Level  %4.1f dB Overall \n Ref: 20 micro Pa',nt,overall_dB);
            ylab='SPL (dB)';
            [fig_num]=plot_bar(fig_num,f,pressure_dB,tstring,ylab);


            fprintf('\n zero dB ref: 20 micro Pa ');
            fprintf('\n\n   fc(Hz) SPL(dB) \n')
            for i=1:num
                fprintf(' %7.4g  %4.1f \n',f(i),pressure_dB(i));
            end

            fprintf('\n Overall Level = %4.1f dB \n\n',overall_dB);

            name=app.OutputArrayNameEditField.Value;
            assignin('base',name,[f pressure_dB]);

        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            open reverberant_room_acoustics.pdf;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.sound_pressure_reverberant_roomver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.sound_pressure_reverberant_roomver10byTomIrvineLabel.Text = 'sound_pressure_reverberant_room    ver 1.0   by  Tom Irvine';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ViewEquationButton = uibutton(app.UIFigure, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.GasListBoxLabel = uilabel(app.UIFigure);
            app.GasListBoxLabel.Text = 'Gas';
            app.GasListBox = uilistbox(app.UIFigure);
            app.GasListBox.Items = {'Air', 'Other'};
            app.GasListBox.ValueChangedFcn = createCallbackFcn(app, @GasListBoxValueChanged, true);
            app.GasListBox.Value = 'Air';
            app.GasDensitylbmin3Label = uilabel(app.UIFigure);
            app.GasDensitylbmin3Label.Text = 'Gas Density (lbm/ft^3)';
            app.GasDensitylbmft3EditField = uieditfield(app.UIFigure, 'text');
            app.SpeedofSoundftsecLabel = uilabel(app.UIFigure);
            app.SpeedofSoundftsecLabel.Text = 'Speed of Sound (ft/sec)';
            app.SpeedofSoundftsecEditField = uieditfield(app.UIFigure, 'text');
            app.UnitsListBoxLabel = uilabel(app.UIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.UIFigure);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.RoomVolumeft3EditFieldLabel = uilabel(app.UIFigure);
            app.RoomVolumeft3EditFieldLabel.Text = 'Room Volume (ft^3)';
            app.RoomVolumeft3EditField = uieditfield(app.UIFigure, 'text');
            app.text17 = uilabel(app.UIFigure);
            app.text17.Tag = 'text17';
            app.text17.Text = {'The input array must be in one-octave or one-third octave format and have at least two columns:'; '   '; '  1.  Center Frequency (Hz)     '; '  2.  Power dB  ref 1 pico Watt    '; '  3.  Reverberation Time (sec)  optional'};
            app.InputArrayNameEditFieldLabel = uilabel(app.UIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIFigure, 'text');
            app.sound_pressure_reverberant_roomver10byTomIrvineLabel_2 = uilabel(app.UIFigure);
            app.sound_pressure_reverberant_roomver10byTomIrvineLabel_2.Text = 'This script calculates the sound pressure in a reverberant room from a point source that radiates uniformly in all directions.';
            app.UniformReverberantTimeseciftheInputArrayonlyhastwocolumnsLabel = uilabel(app.UIFigure);
            app.UniformReverberantTimeseciftheInputArrayonlyhastwocolumnsLabel.Text = {'Uniform Reverberant Time (sec) if the '; 'Input Array only has Two Columns.'};
            app.TREditField = uieditfield(app.UIFigure, 'text');
            app.TREditField.Value = '0.6';
            app.OutputArrayNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputArrayNameEditField.Value = 'room_spl';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_pressure_reverberant_room

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end