classdef spacecraft_clamp_band_SRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIClampBandSRS                 matlab.ui.Figure
        spacecraft_clamp_band_srsver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PreloadTensionTab              matlab.ui.container.Tab
        CalculateButton                matlab.ui.control.Button
        TotalTensileLoadonRingsEditFieldLabel  matlab.ui.control.Label
        TotalTensileLoadonRingsEditField  matlab.ui.control.NumericEditField
        BetaEditFieldLabel             matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        TensileLoadDistributionFactorbetweenUpperLowerRingsLabel  matlab.ui.control.Label
        CrossSectionAreaofSpacecraftLowerRingEditFieldLabel  matlab.ui.control.Label
        CrossSectionAreaofSpacecraftLowerRingEditField  matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PseudoVelocityTab              matlab.ui.container.Tab
        CalculateButton_2              matlab.ui.control.Button
        InitialSlopedBoctaveEditFieldLabel  matlab.ui.control.Label
        InitialSlopedBoctaveEditField  matlab.ui.control.NumericEditField
        PseudoVelocityatKneeFrequencyEditFieldLabel  matlab.ui.control.Label
        PseudoVelocityatKneeFrequencyEditField  matlab.ui.control.NumericEditField
        EnterDataPanel                 matlab.ui.container.Panel
        MaterialListBoxLabel           matlab.ui.control.Label
        MaterialListBox                matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        ElasticModulusEditFieldLabel   matlab.ui.control.Label
        ElasticModulusEditField        matlab.ui.control.NumericEditField
        MassDensityEditFieldLabel      matlab.ui.control.Label
        MassDensityEditField           matlab.ui.control.NumericEditField
        DiameterEditFieldLabel         matlab.ui.control.Label
        DiameterEditField              matlab.ui.control.NumericEditField
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        fr % ring frequency
        CL % longitudinal wave speed
         D % diameter
       rho % mass density
        iu % Units
        ff % output frequency
        aa % output acceleration
    end
    
    methods (Access = private)
        
        function close_figures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end  
            
        end
        
        function LB_units(app)
            value = app.UnitsListBox.Value;
            if(contains(value,'English'))
                app.iu=1;
                app.ElasticModulusEditFieldLabel.Text = 'Elastic Modulus (lbf/in^2)';                  
                app.DiameterEditFieldLabel.Text = 'Diameter (in)';   
                app.MassDensityEditFieldLabel.Text = 'Mass Density (lbm/in^3)';
                app.PseudoVelocityatKneeFrequencyEditFieldLabel.Text='Pseudo Velocity at Knee Frequency (in/sec)';
                app.CrossSectionAreaofSpacecraftLowerRingEditFieldLabel.Text='Cross-Section Area Lower Ring (in^2)';                  
                app.TotalTensileLoadonRingsEditFieldLabel.Text='Total Tensile Load on Rings (lbf)';
                
                if(contains(app.MaterialListBox.Value,'Aluminum'))
                    app.MassDensityEditField.Value=0.1;
                    app.ElasticModulusEditField.Value=1e+07;
                else
                    app.MassDensityEditField.Value=0;
                    app.ElasticModulusEditField.Value=0;                    
                end
                
            else   
                app.iu=2;
                app.ElasticModulusEditFieldLabel.Text = 'Elastic Modulus (GPa)';
                app.DiameterEditFieldLabel.Text = 'Diameter (mm)'; 
                app.MassDensityEditFieldLabel.Text = 'Mass Density (kg/m^3)';
                app.PseudoVelocityatKneeFrequencyEditFieldLabel.Text='Pseudo Velocity at Knee Frequency (m/sec)';                
                app.CrossSectionAreaofSpacecraftLowerRingEditFieldLabel.Text='Cross-Section Area Lower Ring (mm^2)'; 
                app.TotalTensileLoadonRingsEditFieldLabel.Text='Total Tensile Load on Rings (kN)';
                 
                if(contains(app.MaterialListBox.Value,'Aluminum'))
                    app.MassDensityEditField.Value=2700;
                    app.ElasticModulusEditField.Value=70; 
                else
                    app.MassDensityEditField.Value=0;
                    app.ElasticModulusEditField.Value=0; 
                end                
            end
            
        end
        
        function RF(app)
        
            E=app.ElasticModulusEditField.Value;
            app.D=app.DiameterEditField.Value;
            app.rho=app.MassDensityEditField.Value;
            
            if(app.D<1e-06)
                warndlg('Diameter must be > 0')
                return; 
            end
            
            app.iu=2;
            
            if(contains(app.UnitsListBox.Value,'English'))
                app.iu=1;
            end
        
            if(app.iu==1)  % English
                app.rho=app.rho/386;
            else        % metric
    
                [E]=GPa_to_Pa(E);
                app.D=app.D/1000;    
            end  
            
            app.CL=sqrt(E/app.rho);
            app.fr=app.CL/(pi*app.D);

            fprintf('\n * * * * * * * * * \n');
            fprintf('\n  Ring Frequency = %8.4g Hz \n',app.fr);
            
            if(app.fr<=100)
                warndlg(' Ring frequency is too low ');
                return;
            end
            if(app.fr>=9990)
                warndlg(' Ring frequency is too high ');
                return;
            end            
            
        end
        
        function Finish(app)
                disp('  ');
                disp('   SRS Q=10 ');
                disp('  ');
                disp('  fn(Hz)   Accel(G) ');

                for i=1:length(app.ff)
                    fprintf(' %8.4g %8.4g \n',app.ff(i),app.aa(i));
                end
                disp(' ');

                clampband_srs=[ app.ff app.aa ];

                assignin('base','clampband_srs',clampband_srs);

                disp(' SRS output array:  clampband_srs  ');

                fig_num=1;
                fmin=app.ff(1);
                fmax=app.ff(end);
                ppp=clampband_srs;
                t_string='Clamp Band Release SRS Q=10';
                x_label='Natural Frequency (Hz)';
                y_label='Peak Accel (G)';
                md=4;

                [~,~]=plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);

                msgbox('Results written to Command Window');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LB_units(app)
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
                closereq
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            close_figures(app);
            RF(app)
            
            omegan=2*pi*app.fr;

            pv=app.PseudoVelocityatKneeFrequencyEditField.Value;
            
            if(pv<1e-06)
                warndlg('Pseudo Velocity must be > 0');
                return;
            else
            
                a_plateau=pv*omegan;
            
                if(app.iu==1)
                    a_plateau=a_plateau/386;
                else
                    a_plateau=a_plateau/9.81;    
                end
            
                slope=app.InitialSlopedBoctaveEditField.Value;

                noct=log2(app.fr/10);
                dB=-abs(slope)*noct;

                a1=a_plateau*10^(dB/20);

                app.ff=[10 app.fr 10000]';
                app.aa=[a1 a_plateau a_plateau]';

                Finish(app);                
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            close_figures(app);
            RF(app)
            
            beta=app.BetaEditField.Value;
            T=app.TotalTensileLoadonRingsEditField.Value;
            A=app.CrossSectionAreaofSpacecraftLowerRingEditField.Value;
            
            if(T<1.e-06)
                warndlg('Tension must be > 0')
                return;
            end
            if(A<1.e-06)
                warndlg('Area must be > 0')
                return;
            end            
            
            if(app.iu==2)  % metric
                T=T*1000;
                A=A/1000^2;
            end    

            R=app.D/2;

            a_plateau=(beta*T)/(app.rho*A*R);


            if(app.iu==1)
                a_plateau=a_plateau/386;
            else
                a_plateau=a_plateau/9.81;    
            end

            n=1;

            freq_ratio=(app.fr/10)^n;

            a1=a_plateau/freq_ratio;

            app.ff=[10 app.fr 10000]';
            app.aa=[a1 a_plateau a_plateau]';

            Finish(app);

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            LB_units(app)
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            LB_units(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIClampBandSRS = uifigure('Visible', 'off');
            app.UIClampBandSRS.Name = 'UI Clamp Band SRS';
            app.spacecraft_clamp_band_srsver10byTomIrvineLabel = uilabel(app.UIClampBandSRS);
            app.spacecraft_clamp_band_srsver10byTomIrvineLabel.Text = {'spacecraft_clamp_band_srs   ver 1.0  by Tom Irvine      Separation source shock estimate.   Select method from tabs.'; ''};
            app.TabGroup = uitabgroup(app.UIClampBandSRS);
            app.PreloadTensionTab = uitab(app.TabGroup);
            app.PreloadTensionTab.Title = 'Preload Tension';
            app.CalculateButton = uibutton(app.PreloadTensionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalTensileLoadonRingsEditFieldLabel = uilabel(app.PreloadTensionTab);
            app.TotalTensileLoadonRingsEditFieldLabel.Text = {'Total Tensile Load on Rings'; ''};
            app.TotalTensileLoadonRingsEditField = uieditfield(app.PreloadTensionTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.PreloadTensionTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.PreloadTensionTab, 'numeric');
            app.BetaEditField.Value = 0.5;
            app.TensileLoadDistributionFactorbetweenUpperLowerRingsLabel = uilabel(app.PreloadTensionTab);
            app.TensileLoadDistributionFactorbetweenUpperLowerRingsLabel.Text = 'Tensile Load Distribution Factor between Upper & Lower Rings';
            app.CrossSectionAreaofSpacecraftLowerRingEditFieldLabel = uilabel(app.PreloadTensionTab);
            app.CrossSectionAreaofSpacecraftLowerRingEditFieldLabel.Text = 'Cross-Section Area of Spacecraft Lower Ring';
            app.CrossSectionAreaofSpacecraftLowerRingEditField = uieditfield(app.PreloadTensionTab, 'numeric');
            app.Label = uilabel(app.PreloadTensionTab);
            app.Label.Text = {'Reference:   Mechanical Shock Design and Verification Handbook'; ' ECSS-E-HB-32-25A  Section 7.4.3.2'; ''; ''};
            app.PseudoVelocityTab = uitab(app.TabGroup);
            app.PseudoVelocityTab.Title = 'Pseudo Velocity';
            app.CalculateButton_2 = uibutton(app.PseudoVelocityTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.InitialSlopedBoctaveEditFieldLabel = uilabel(app.PseudoVelocityTab);
            app.InitialSlopedBoctaveEditFieldLabel.Text = 'Initial Slope (dB/octave)';
            app.InitialSlopedBoctaveEditField = uieditfield(app.PseudoVelocityTab, 'numeric');
            app.InitialSlopedBoctaveEditField.Value = 9;
            app.PseudoVelocityatKneeFrequencyEditFieldLabel = uilabel(app.PseudoVelocityTab);
            app.PseudoVelocityatKneeFrequencyEditFieldLabel.Text = 'Pseudo Velocity at Knee Frequency';
            app.PseudoVelocityatKneeFrequencyEditField = uieditfield(app.PseudoVelocityTab, 'numeric');
            app.EnterDataPanel = uipanel(app.UIClampBandSRS);
            app.EnterDataPanel.Title = 'Enter Data';
            app.MaterialListBoxLabel = uilabel(app.EnterDataPanel);
            app.MaterialListBoxLabel.Text = {'Material'; ''};
            app.MaterialListBox = uilistbox(app.EnterDataPanel);
            app.MaterialListBox.Items = {'Aluminum', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.UnitsListBoxLabel = uilabel(app.EnterDataPanel);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.EnterDataPanel);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.ElasticModulusEditFieldLabel = uilabel(app.EnterDataPanel);
            app.ElasticModulusEditFieldLabel.Text = 'Elastic Modulus';
            app.ElasticModulusEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.MassDensityEditFieldLabel = uilabel(app.EnterDataPanel);
            app.MassDensityEditFieldLabel.Text = 'Mass Density';
            app.MassDensityEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.DiameterEditFieldLabel = uilabel(app.EnterDataPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.EnterDataPanel, 'numeric');
            app.ReturnButton = uibutton(app.UIClampBandSRS, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIClampBandSRS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spacecraft_clamp_band_SRS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIClampBandSRS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIClampBandSRS)
        end
    end
end