classdef srs_base_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataSRS             matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculateTab                   matlab.ui.container.Tab
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        CalculateSRSButton             matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        UpdateListButton               matlab.ui.control.Button
        InputTimeUnitListBox           matlab.ui.control.ListBox
        InputTimeUnitListBoxLabel      matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        FrequencySpacingListBox        matlab.ui.control.ListBox
        FrequencySpacingListBoxLabel   matlab.ui.control.Label
        AmplificationFactorQEditField  matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        MinFreqHzEditField             matlab.ui.control.NumericEditField
        MinFreqHzEditFieldLabel        matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        MaxFreqHzEditField             matlab.ui.control.NumericEditField
        MaxFreqHzEditFieldLabel        matlab.ui.control.Label
        PlottingLabel                  matlab.ui.control.Label
        IncludeResidualListBox         matlab.ui.control.ListBox
        IncludeResidualListBoxLabel    matlab.ui.control.Label
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        ResultsTab                     matlab.ui.container.Tab
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        SaveTab                        matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        OutputTypeListBox              matlab.ui.control.ListBox
        OutputTypeListBoxLabel         matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        srs_base_ahbyTomIrvinever12Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM    % Time history
        iflag  %
        accel_rms     %
        accel_data    %
        p_vel_data    %
        rel_disp_data %
        tstart   %
        tend     %
    end
    
    methods (Access = private)
        
        function update(app)
            clear(app);
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};            
        end
        
        function PreProcess(app)
        
            ClosePlots(app);
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                     
            ns=app.SignalProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);

        end
        
        function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                          
        end
        
        function clear(app)
            app.SaveButton.Enable='off';
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end

        function TimeChoice(app)
            n=app.SignalProcessingListBox.Value;
            if(contains(n,'Entire'))
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditField.Visible='off'; 
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';                 
            else    
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';              
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TimeChoice(app);
            clear(app);
            update(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         
            Items = char(app.OutputTypeListBox.Items);
            im=app.OutputTypeListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel_data;
            end
            if(n==2)
                data=app.p_vel_data;
            end    
            if(n==3)
                data=app.rel_disp_data;
            end
            if(n==4)
                adata=app.accel_data;
            end              
            if(n==5)
                adata=app.p_vel_data;
            end
            if(n==6)
                adata=app.rel_disp_data;
            end  
            if(n==7)
                data=app.accel_rms;
            end           

            if(n>=4 && n<=6)
       
                m=length(adata(:,1));
    
                for i=1:m
                    data(i,:)=[adata(i,1) max([adata(i,2) adata(i,3)])];
                end  

            end  

            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name, data); 
            msgbox('Data saved');                  
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function CalculateFFTButtonPushed(app, event)
            
        end

        % Callback function
        function SignalProcessingListBoxValueChanged(app, event)
            TimeChoice(app);
            clear(app);
        end

        % Button pushed function: CalculateSRSButton
        function CalculateSRSButtonPushed(app, event)
            PreProcess(app);

            if(app.iflag==999)
                return;
            end

            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            if(iflagq~=1)
	            return
            end

            fig_num=1;
            
            Items = char(app.IncludeResidualListBox.Items);
            im=app.IncludeResidualListBox.Value;
            [res]=find_index(Items,im);

            Items = char(app.FrequencySpacingListBox.Items);
            im=app.FrequencySpacingListBox.Value;
            [fspace]=find_index(Items,im);
            
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [iu]=find_index(Items,im);

            Items = char(app.InputTimeUnitListBox.Items);
            im=app.InputTimeUnitListBox.Value;
            [itu]=find_index(Items,im);
     
            tor=app.THM(:,1);
            
            if(itu==2)
               app.THM(:,1)=app.THM(:,1)/1000; 
            end    
            
            y=double(app.THM(:,2));
            yy=y;
            
            dt=mean(diff(app.THM(:,1)));
            sr=1/dt;

            Q=app.AmplificationFactorQEditField.Value;
            Q=str2num(Q);

            fstart=app.MinFreqHzEditField.Value;
              fend=app.MaxFreqHzEditField.Value;
              
            if(isempty(fstart))
               warndlg('Enter Min Plot Frequency');
               return;
            end
            if(isempty(fend))
               warndlg('Enter Max Plot Frequency');
               return;
            end

            if(fstart>=fend)
               warndlg('Frequency Limit Error');
               return; 
            end
              
            if(fstart==0)
               fstart=0.1; 
            end
                          
            damp=1/(2*Q);
            
            fmax=sr/5;
            
            [fn,num]=srs_fn(fspace,fstart,fend,fmax);

            if(isempty(fn))
                warndlg('Check sample rate & frequency limits');
                return;
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            
            try
            
                [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);                          
            catch
                warndlg('srs_coefficients failed');
                return;
            end
            %
            %  SRS engine
            %
            
            a_rms=zeros(num,1);
            a_pos=zeros(num,1);
            a_neg=zeros(num,1);
            rd_pos=zeros(num,1);
            rd_neg=zeros(num,1);
            pv_pos=zeros(num,1);
            pv_neg=zeros(num,1);
            
            NL=length(yy);
            
            tic
            % progressbar;
            for j=1:num
            %
            %    progressbar(j/num); 
            %
                if(res==1)
                    ML=NL+round((1/fn(j))/dt);
                    ys=zeros(ML,1);
                    ys(1:NL)=yy;
                else
                    ys=yy;
                end
            %  
                [~,a_pos(j),a_neg(j),a_rms(j)]=arbit_engine_srs_vrs(a1(j),a2(j),b1(j),b2(j),b3(j),ys);
            %
                [~,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),ys);
            %
                if(iu==1)
                    scale_rd=386;
                else    
                    scale_rd=9.81*1000;                
                end
                
                rd_pos(j)=rd_pos(j)*scale_rd;
                rd_neg(j)=rd_neg(j)*scale_rd;        
            %
                omegan=2*pi*fn(j);
                pv_pos(j)=omegan*rd_pos(j);
                pv_neg(j)=omegan*rd_neg(j);    
            %
                if(iu==2)
                    pv_pos(j)=pv_pos(j)/10;
                    pv_neg(j)=pv_neg(j)/10;
                end
            %
            end
            %
            % pause(0.3);
            % progressbar(1);
             toc
            %
            a_neg=abs(a_neg);
            pv_neg=abs(pv_neg);
            rd_neg=abs(rd_neg);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            fn=fix_size(fn);
            a_pos=fix_size(a_pos);
            a_neg=fix_size(a_neg);
            pv_pos=fix_size(pv_pos);
            pv_neg=fix_size(pv_neg);
            rd_pos=fix_size(rd_pos);
            rd_neg=fix_size(rd_neg);
            %
            app.accel_rms=[fn a_rms];
            app.accel_data=[fn a_pos a_neg];
            app.p_vel_data=[fn pv_pos pv_neg];
            app.rel_disp_data=[fn rd_pos rd_neg];
            %
            h=figure(fig_num);
            fig_num=fig_num+1;
            plot(tor,app.THM(:,2));
            title('Base Input Acceleration Time History');
            
            if(itu==1)
                xlabel(' Time (sec) ')
            else
                xlabel(' Time (msec) ')    
            end
            
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            grid on;
            
           
            fmin=fstart;
            fmax=fend;
            
            t_string=sprintf('Acceleration SRS Q=%g',Q);
            
            if(iu==1 || iu==2)
                y_lab='Peak Accel (G)';
            else
                y_lab='Peak Accel (m/sec^2)';    
            end
            
            
            [fig_num,h]=srs_plot_function_h(fig_num,fn,a_pos,a_neg,t_string,y_lab,fmin,fmax);
            
            
            t_string=sprintf('Pseudo Velocity SRS Q=%g',Q);
            if(iu==1)
                y_lab='Peak Vel (in/sec)';
            else
                y_lab='Peak Vel (cm/sec)';    
            end
            [fig_num,h]=srs_plot_function_h(fig_num,fn,pv_pos,pv_neg,t_string,y_lab,fmin,fmax);
            
            
            t_string=sprintf('Relative Displacement SRS Q=%g',Q);
            if(iu==1)
                y_lab='Peak Rel Disp (in)';
            else
                y_lab='Peak Rel Disp (mm)';    
            end
            [fig_num,h]=srs_plot_function_h(fig_num,fn,rd_pos,rd_neg,t_string,y_lab,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            n=length(fn);
            
            for i=1:n
                if(fn(i)>fend)
                    i=i-1;
                    break;
                end
            end
            
            pv_srs=[fn(1:i) pv_pos(1:i) pv_neg(1:i) ];
            
            [fig_num,h]=srs_tripartite_function_h(iu,fig_num,pv_srs,Q);
            
            mfn=0;
            maxa=0;
            
            for i=1:length(fn)
                if( a_pos(i) > maxa)
                    maxa=a_pos(i);
                    mfn=fn(i);
                end
                if( a_neg(i) > maxa)
                    maxa=a_neg(i);
                    mfn=fn(i);
                end    
            end
            
            mfnv=0;
            maxv=0;
            
            for i=1:length(pv_srs(:,1))
                if( pv_srs(i,2) > maxv)
                    maxv=pv_srs(i,2);
                    mfnv=fn(i);
                end
                if( pv_srs(i,3) > maxv)
                    maxv=pv_srs(i,3);
                    mfnv=fn(i);
                end    
            end
            
            yl='G';
            
            if(iu==1)
                y2='in/sec';    
            else 
                y2='cm/sec';      
            end
   
            s1=sprintf(' Maximum Responses  Q=%g',Q);
            s2=sprintf('  Acceleration:  %8.4g Hz  %8.4g %s  ',mfn,maxa,yl);
            s3=sprintf('  Pseudovelocity:  %8.4g Hz  %8.4g %s  ',mfnv,maxv,y2);
             
            ss=sprintf('%s\n\n%s\n%s',s1,s2,s3);
            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            app.SaveButton.Enable='on';

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Callback function
        function UnitsListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: InputTimeUnitListBox
        function InputTimeUnitListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: FrequencySpacingListBox
        function FrequencySpacingListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: IncludeResidualListBox
        function IncludeResidualListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged2(app, event)
            TimeChoice(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataSRS = uifigure('Visible', 'off');
            app.UIVibrationdataSRS.Name = 'SRS';
            app.srs_base_ahbyTomIrvinever12Label = uilabel(app.UIVibrationdataSRS);
            app.srs_base_ahbyTomIrvinever12Label.Text = 'srs_base_ah   by Tom Irvine   ver 1.7          Shock Response Spectrum via the Smallwood ramp invariant digital recursive filtering relationship';
            app.ReturnButton = uibutton(app.UIVibrationdataSRS, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.UIVibrationdataSRS);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.CalculateTab);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec) & amplitude';
            app.IncludeResidualListBoxLabel = uilabel(app.CalculateTab);
            app.IncludeResidualListBoxLabel.Text = 'Include Residual';
            app.IncludeResidualListBox = uilistbox(app.CalculateTab);
            app.IncludeResidualListBox.Items = {'Yes', 'No'};
            app.IncludeResidualListBox.ValueChangedFcn = createCallbackFcn(app, @IncludeResidualListBoxValueChanged, true);
            app.IncludeResidualListBox.Value = 'Yes';
            app.PlottingLabel = uilabel(app.CalculateTab);
            app.PlottingLabel.Text = 'Plotting';
            app.MaxFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.MaxFreqHzEditField.Value = 10000;
            app.StartTimesecEditFieldLabel = uilabel(app.CalculateTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.CalculateTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.CalculateTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.CalculateTab, 'numeric');
            app.MinFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.MinFreqHzEditField.Value = 10;
            app.AmplificationFactorQEditFieldLabel = uilabel(app.CalculateTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.CalculateTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.FrequencySpacingListBoxLabel = uilabel(app.CalculateTab);
            app.FrequencySpacingListBoxLabel.Text = 'Frequency Spacing';
            app.FrequencySpacingListBox = uilistbox(app.CalculateTab);
            app.FrequencySpacingListBox.Items = {'1/12 octave', '1/24 octave', '1 Hz constant', '0.5 Hz constant'};
            app.FrequencySpacingListBox.ValueChangedFcn = createCallbackFcn(app, @FrequencySpacingListBoxValueChanged, true);
            app.FrequencySpacingListBox.Value = '1/12 octave';
            app.UnitsListBoxLabel = uilabel(app.CalculateTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.CalculateTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm'};
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.SignalProcessingListBoxLabel = uilabel(app.CalculateTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.CalculateTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged2, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.InputTimeUnitListBoxLabel = uilabel(app.CalculateTab);
            app.InputTimeUnitListBoxLabel.Text = 'Input Time Unit';
            app.InputTimeUnitListBox = uilistbox(app.CalculateTab);
            app.InputTimeUnitListBox.Items = {'sec', 'msec'};
            app.InputTimeUnitListBox.ValueChangedFcn = createCallbackFcn(app, @InputTimeUnitListBoxValueChanged, true);
            app.InputTimeUnitListBox.Value = 'sec';
            app.UpdateListButton = uibutton(app.CalculateTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.listbox_method = uilistbox(app.CalculateTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.CalculateSRSButton = uibutton(app.CalculateTab, 'push');
            app.CalculateSRSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSRSButtonPushed, true);
            app.CalculateSRSButton.Text = 'Calculate SRS';
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculateTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculateTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTextAreaLabel = uilabel(app.ResultsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ResultsTab);
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.OutputTypeListBoxLabel = uilabel(app.SaveTab);
            app.OutputTypeListBoxLabel.Text = 'Output Type';
            app.OutputTypeListBox = uilistbox(app.SaveTab);
            app.OutputTypeListBox.Items = {'Acceleration SRS - pos & neg', 'Pseudo Velocity SRS - pos & neg', 'Relative Displacement SRS - pos & neg', 'Acceleration SRS - absolute', 'Pseudo Velocity SRS - absolute', 'Relative Displacement SRS - absolute', 'Acceleration VRS'};
            app.OutputTypeListBox.Value = 'Acceleration SRS - pos & neg';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIVibrationdataSRS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = srs_base_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIVibrationdataSRS)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIVibrationdataSRS)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataSRS)
        end
    end
end