classdef srs_spec_from_time_history_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputArrayTab                matlab.ui.container.Tab
        SRSCalculationTab            matlab.ui.container.Tab
        ThirdQEditField              matlab.ui.control.EditField
        ThirdQEditFieldLabel         matlab.ui.control.Label
        SecondQEditField             matlab.ui.control.EditField
        SecondQEditFieldLabel        matlab.ui.control.Label
        FirstQEditField              matlab.ui.control.EditField
        FirstQEditFieldLabel         matlab.ui.control.Label
        EndFreqHzEditField           matlab.ui.control.EditField
        EndFreqHzEditFieldLabel      matlab.ui.control.Label
        StartFreqHzEditField         matlab.ui.control.EditField
        StartFreqHzEditFieldLabel    matlab.ui.control.Label
        NumberofQValuesListBox       matlab.ui.control.ListBox
        NumberofQValuesListBoxLabel  matlab.ui.control.Label
        NewSpecFormatListBox         matlab.ui.control.ListBox
        NewSpecFormatListBoxLabel    matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        ExporttoNastranTab           matlab.ui.container.Tab
        ExportButton                 matlab.ui.control.Button
        TheinputtimehistorymusthavetwocolumnsTimesecAccelLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ReturnButton                 matlab.ui.control.Button
        srs_base_ahbyTomIrvinever12Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        qnum % Description
    end
    
    methods (Access = private)

        function NumQ(app)            

            Items = app.NumberofQValuesListBox.Items;
            im = strtrim(app.NumberofQValuesListBox.Value);
            app.qnum = find(strcmp(Items, im));

            app.SecondQEditField.Visible='off';
            app.ThirdQEditField.Visible='off';
            app.SecondQEditFieldLabel.Visible='off';
            app.ThirdQEditFieldLabel.Visible='off';

            if(app.qnum>=2)
                app.SecondQEditField.Visible='on';
                app.SecondQEditFieldLabel.Visible='on';
            end
            if(app.qnum==3)
                app.ThirdQEditField.Visible='on';
                app.ThirdQEditFieldLabel.Visible='on';            
            end            

        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            NumQ(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            setappdata(0,'nastran_srs',1);
            handles.s=export_junction;
            set(handles.s,'Enable','on');
        end

        % Value changed function: NumberofQValuesListBox
        function NumberofQValuesListBoxValueChanged(app, event)

            NumQ(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            ClosePlots(app);

            fmin=str2num(app.StartFreqHzEditField.Value);
            fmax=str2num(app.EndFreqHzEditField.Value);

            Items = app.NewSpecFormatListBox.Items;
            im = strtrim(app.NewSpecFormatListBox.Value);
            app.iformat = find(strcmp(Items, im));

            if(app.qnum==1)
                new_Q(1)=str2num(app.FirstQEditField.Value);
            end
            if(app.qnum==2)
                new_Q(2)=str2num(app.FirstQEditField.Value);
            end
            if(app.qnum==3)
                new_Q(3)=str2num(app.FirstQEditField.Value);
            end

        tic;
        
        
        iunit=get(handles.listbox_units,'Value');
        
        
        %%  h = msgbox('Intermediate Results are written in Command Window');
        
        k=get(handles.listbox_method,'Value');
        
        if(k==1)
          FS=get(handles.edit_input_array,'String');
          THM=evalin('base',FS);   
        else
          THM=getappdata(0,'THM');
        end
        
        nQ=get(handles.listbox_num_Q,'Value');
        
        new_Q=zeros(nQ,1);
        new_damp=zeros(nQ,1);
        
        for i=1:nQ
            
            if(i==1)
                new_Q(i)=str2num(get(handles.edit_Q1,'String'));
            end
            if(i==2)
                new_Q(i)=str2num(get(handles.edit_Q2,'String'));
            end
            if(i==3)
                new_Q(i)=str2num(get(handles.edit_Q3,'String'));
            end    
            
            if(isempty(new_Q(i)))
                warndlg(' Enter New Q ');
                return;
            end 
            
            new_damp(i) = (1/(2*new_Q(i)));
            
        end
        
        %%%%
        
        t=THM(:,1);
        yy=THM(:,2);
        %
        tmx=max(t);
        tmi=min(t);
        n = length(t);
        dt=(tmx-tmi)/(n-1);
        sr=1/dt;
        %
        disp(' ')
        disp(' Time Step ');
        dtmin=min(diff(t));
        dtmax=max(diff(t));
        %
        out4 = sprintf(' dtmin  = %8.4g sec  ',dtmin);
        out5 = sprintf(' dt     = %8.4g sec  ',dt);
        out6 = sprintf(' dtmax  = %8.4g sec  ',dtmax);
        disp(out4)
        disp(out5)
        disp(out6)
        %
        disp(' ')
        disp(' Sample Rate ');
        out4 = sprintf(' srmin  = %8.4g samples/sec  ',1/dtmax);
        out5 = sprintf(' sr     = %8.4g samples/sec  ',1/dt);
        out6 = sprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
        disp(out4)
        disp(out5)
        disp(out6)
        %
        
        if(((dtmax-dtmin)/dt)>0.01)
            disp(' ')
            disp(' Warning:  time step is not constant.  Continue calculation? 1=yes 2=no ')
            ncontinue=input(' ');
            
            if(ncontinue==2)
                return;
            end
        end
        
        %%%%
        
        dt=1/sr; 
        
        fstart=str2num(get(handles.edit_fstart,'String'));
        fend  =str2num(get(handles.edit_fend,'String'));
        
        
        fmax=sr/8;
        
        num=1;
        
        fn(1)=fstart;
        
        fspace=1;
        
        if(fspace==1)
        
            oct=2^(1/12);
        
            while(1)
            
                num=num+1;
          
                fn(num)=fn(num-1)*oct;
          
                if(fn(num)>fmax || fn(num)>=fend)
                    break;
                end
            
            end
        
        end
        
        if(fn(num)>fend)
            fn(num)=fend;
        end
        
        
        fmin=fstart;
        fmax=fend;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        fig_num=1;
        
        if(iunit<=2)
            y_lab='Accel(G)';
        else
            y_lab='Accel(m/sec^2)';    
        end  
        
        figure(fig_num);
        plot(t,yy);
        title('Acceleration Base Input');
        grid on;
        ylabel(y_lab);
        xlabel('Time (sec)');
        fig_num=fig_num+1;
        
        
        disp(' ');
        disp(' ');
        
        NT=8000;
        
        md=6;
        
        x_label='Natural Frequency (Hz)';
        y_label=y_lab;
        
        
        srs_type=get(handles.listbox_type,'Value');
        format=get(handles.listbox_format,'Value');
        
        NL=length(fn);
        
        
        for ijk=1:nQ
        
            [new_srs_pn]=srs_function(yy,dt,new_damp(ijk),fn);
        
            a_pos=new_srs_pn(:,2);
            a_neg=new_srs_pn(:,3);
        
            a_abs=zeros(NL,1);
            a_abs(:,1)=fn;
        
            for i=1:NL
                a_abs(i)=max([ a_pos(i) a_neg(i)]); 
            end      
            
            if(srs_type==1)
                [rec_new_spec]=srs_average_envelope(fn,a_abs,NT,format);
            else
                [rec_new_spec]=srs_maximum_envelope(fn,a_abs,NT,format);
            end
            
           
            if(ijk==1)
                rec_new_spec_1=rec_new_spec;
            end
            if(ijk==2)
                rec_new_spec_2=rec_new_spec;
            end
            if(ijk==3)
                rec_new_spec_3=rec_new_spec;
            end    
        
         % t_string = sprintf(' Shock Response Spectrum Q=%g ',new_Q1);  
        
        % [fig_num]=srs_plot_function(fig_num,fn,a_pos,a_neg,t_string,y_lab,fmin,fmax);   
            
            fn=fix_size(fn);
            a_pos=fix_size(a_pos);
            a_neg=fix_size(a_neg);
            
            ppp1=rec_new_spec;
            ppp2=[ fn a_pos ];
            ppp3=[ fn a_neg ];
        
            leg1='Spec';
            leg2='Positive';
            leg3='Negative';
            
                
            t_string=sprintf('Shock Response Spectra  Q=%g',new_Q(ijk));
        
            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                        y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);
        
        end
        
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %
        %  format = 1:  ramp & plateau
        %           2:  three coordinates arbitrary
        %           3:  four coordinates arbitrary
        
        if(format<=2)
            nsrs=3;
        else
            nsrs=4;
        end


        disp(' ');
        disp('Output arrays: ');
        disp(' ');
        
        
        sss=sprintf('%g',new_Q(1));
        sss = strrep(sss,'.','p');
        output_S1=sprintf('srs_spec_new_Q%s',sss);
        assignin('base', output_S1, rec_new_spec_1);
        disp(output_S1);
        
         
        if(nQ>=2)
            sss=sprintf('%g',new_Q(2));
            sss = strrep(sss,'.','p');    
            output_S2=sprintf('srs_spec_new_Q%s',sss);
            assignin('base', output_S2, rec_new_spec_2); 
            disp(output_S2);
        end 
         
        if(nQ>=3)
            sss=sprintf('%g',new_Q(3));
            sss = strrep(sss,'.','p');    
            output_S3=sprintf('srs_spec_new_Q%s',sss);
            assignin('base', output_S3, rec_new_spec_3); 
            disp(output_S3);
        end 
        
        %%%%
            
        out1=sprintf('\n New SRS Specification Q=%g ',new_Q(1));
        disp(out1);
        disp('     fn(Hz)   Accel(G)');
        disp(' ');
        
        for i=1:nsrs
            out1=sprintf(' %9.5g  %9.5g',rec_new_spec_1(i,1),rec_new_spec_1(i,2));
            disp(out1);  
        end    
        
        if(nQ>=2)
            
            out1=sprintf('\n New SRS Specification Q=%g ',new_Q(2));
            disp(out1);
            disp('     fn(Hz)   Accel(G)');
            disp(' ');
        
            for i=1:nsrs
                out1=sprintf(' %9.5g  %9.5g',rec_new_spec_2(i,1),rec_new_spec_2(i,2));
                disp(out1);  
            end        
            
        end   
        if(nQ>=3)
            
            out1=sprintf('\n New SRS Specification Q=%g ',new_Q(3));
            disp(out1);
            disp('     fn(Hz)   Accel(G)');
            disp(' ');
        
            for i=1:nsrs
                out1=sprintf(' %9.5g  %9.5g',rec_new_spec_3(i,1),rec_new_spec_3(i,2));
                disp(out1);  
            end        
            
        end 
        
        
        %%%%%%%%%%%%%%
        
        disp(' ');
        
        
        md=5;
        
        if(nQ==1)
            
            t_string=sprintf('Shock Response Spectrum  Q=%g',new_Q(1));
        
            ppp=rec_new_spec_1;
            
           [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);    
         
        end
        if(nQ==2)
            
            t_string=sprintf('Shock Response Spectrum');   
            
            leg2=sprintf('Q=%g',new_Q(1));
            leg1=sprintf('Q=%g',new_Q(2));
        
            ppp2=rec_new_spec_1;
            ppp1=rec_new_spec_2; 
            
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md); 
        end
        if(nQ==3)
            
            t_string=sprintf('Shock Response Spectrum');  
            
            leg3=sprintf('Q=%g',new_Q(1));        
            leg2=sprintf('Q=%g',new_Q(2));
            leg1=sprintf('Q=%g',new_Q(3));
        
            ppp3=rec_new_spec_1;
            ppp2=rec_new_spec_2;
            ppp1=rec_new_spec_3;
            
            [fig_num,~]=plot_loglog_function_md_three_h2(fig_num,x_label,...
                       y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);     
        end
        
        set(handles.pushbutton_export,'Enable','on');
        
        
        toc


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.srs_base_ahbyTomIrvinever12Label = uilabel(app.UIFigure);
            app.srs_base_ahbyTomIrvinever12Label.Text = 'srs_spec_from_time_history   by Tom Irvine   ver 1.0      ';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This script drive an SRS specification from an base input acceleration time history.';
            app.TheinputtimehistorymusthavetwocolumnsTimesecAccelLabel = uilabel(app.UIFigure);
            app.TheinputtimehistorymusthavetwocolumnsTimesecAccelLabel.Text = 'The input time history must have two columns:  Time (sec) & Accel';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputArrayTab = uitab(app.TabGroup);
            app.InputArrayTab.Title = 'Input Array';
            app.SRSCalculationTab = uitab(app.TabGroup);
            app.SRSCalculationTab.Title = 'SRS Calculation';
            app.CalculateButton = uibutton(app.SRSCalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NewSpecFormatListBoxLabel = uilabel(app.SRSCalculationTab);
            app.NewSpecFormatListBoxLabel.Text = 'New Spec Format';
            app.NewSpecFormatListBox = uilistbox(app.SRSCalculationTab);
            app.NewSpecFormatListBox.Items = {'Ramp & Plateau         ', 'Three Coordinates Arbitrary', 'Four Coordinates Arbitrary'};
            app.NewSpecFormatListBox.Value = 'Ramp & Plateau         ';
            app.NumberofQValuesListBoxLabel = uilabel(app.SRSCalculationTab);
            app.NumberofQValuesListBoxLabel.Text = 'Number of Q Values';
            app.NumberofQValuesListBox = uilistbox(app.SRSCalculationTab);
            app.NumberofQValuesListBox.Items = {'1', '2', '3'};
            app.NumberofQValuesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofQValuesListBoxValueChanged, true);
            app.NumberofQValuesListBox.Value = '1';
            app.StartFreqHzEditFieldLabel = uilabel(app.SRSCalculationTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.SRSCalculationTab, 'text');
            app.EndFreqHzEditFieldLabel = uilabel(app.SRSCalculationTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.SRSCalculationTab, 'text');
            app.FirstQEditFieldLabel = uilabel(app.SRSCalculationTab);
            app.FirstQEditFieldLabel.Text = 'First Q';
            app.FirstQEditField = uieditfield(app.SRSCalculationTab, 'text');
            app.FirstQEditField.Value = '10';
            app.SecondQEditFieldLabel = uilabel(app.SRSCalculationTab);
            app.SecondQEditFieldLabel.Text = 'Second Q';
            app.SecondQEditField = uieditfield(app.SRSCalculationTab, 'text');
            app.SecondQEditField.Value = '20';
            app.ThirdQEditFieldLabel = uilabel(app.SRSCalculationTab);
            app.ThirdQEditFieldLabel.Text = 'Third Q';
            app.ThirdQEditField = uieditfield(app.SRSCalculationTab, 'text');
            app.ThirdQEditField.Value = '50';
            app.ExporttoNastranTab = uitab(app.TabGroup);
            app.ExporttoNastranTab.Title = 'Export to Nastran';
            app.ExportButton = uibutton(app.ExporttoNastranTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = srs_spec_from_time_history_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end