classdef srs_tripartite_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SRSTripartiteUIFigure           matlab.ui.Figure
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        srs_tripartite_plotver10byTomIrvineLabel_3  matlab.ui.control.Label
        srs_tripartite_plotver10byTomIrvineLabel_2  matlab.ui.control.Label
        RowListBox                      matlab.ui.control.ListBox
        RowListBoxLabel                 matlab.ui.control.Label
        DeleteRowBelowButton            matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        MaxEnvelopeOutputNameEditField  matlab.ui.control.EditField
        MaxEnvelopeOutputNameEditFieldLabel  matlab.ui.control.Label
        PlotMaximumEnvelopeCheckBox     matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NumberListBox                   matlab.ui.control.ListBox
        NumberListBoxLabel              matlab.ui.control.Label
        srs_tripartite_plotver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        num  %
        pmax 
    end
    
    methods (Access = private)

        function plot_max(app)
            app.pmax=app.PlotMaximumEnvelopeCheckBox.Value;

            if(app.pmax==1)
                app.MaxEnvelopeOutputNameEditField.Visible='on';
                app.MaxEnvelopeOutputNameEditFieldLabel.Visible='on';
            else
                app.MaxEnvelopeOutputNameEditField.Visible='off';    
                app.MaxEnvelopeOutputNameEditFieldLabel.Visible='off';
            end
        end
        
        function msweep(app)
            app.num = str2num(app.NumberListBox.Value);  

            Nrows=app.num;
            Ncolumns=4;
             
            A=app.UITable.Data;
             
            sz=size(A);
            Arows=sz(1);
             
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}='';
                end
                data_s{i,1}=sprintf('%d',i);
            end    
             
            if(~isempty(A))
                
                M=min([ Arows Nrows ]);
                
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end    
                end   
             
            end
             
            app.UITable.Data=data_s;

        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            app.UITable.ColumnFormat={'char','char','char','logical'};
            app.UITable.ColumnWidth={49,165,165,50};
            msweep(app);
            plot_max(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: NumberListBox
        function NumberListBoxValueChanged(app, event)
            msweep(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.num = str2num(app.NumberListBox.Value);  
            Nrows=app.num;

            Ncolumns=4;
             
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}='';
                end    
                data_s{i,1}=sprintf('%d',i);
            end  
            
            app.UITable.Data=data_s;
        end

        % Button pushed function: DeleteRowBelowButton
        function DeleteRowBelowButtonPushed(app, event)
            app.num = str2num(app.NumberListBox.Value);  
            Nrows=app.num;

            Ncolumns=4;
            A=app.UITable.Data;
             
            sz=size(A);
            Arows=sz(1);

            data_s = cellstr(num2str((1:Nrows)', '%d'));
            data_s(:, 2:Ncolumns) = {''};
             
            if(~isempty(A))
                
                M=min([ Arows Nrows ]);
                
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}='';
                        try
                            data_s{i,j}=A{i,j};
                        catch
                        end
                        
                    end    
                end   
             
            end
             
            nd=str2num(app.RowListBox.Value);
             
            if(nd<=Nrows)
                
                temp=data_s;
                clear data_s;
                
                for i=1:(nd-1)
                    for j=1:Ncolumns
                        try
                            data_s{i,j}=temp{i,j};
                        catch
                        end
                        
                    end  
                    data_s{i,1}=sprintf('%d',i);
                end  
                
                for i=(nd+1):Nrows
                    for j=1:Ncolumns
                        try
                            data_s{i-1,j}=temp{i,j};
                        catch
                        end
                        
                    end 

                end      
                
                Nrows=Nrows-1;
                app.num = Nrows;
                
                app.NumberListBox.Value=app.NumberListBox.Items{Nrows};

            end

            for i=1:Nrows
                data_s{i,1}=sprintf('%d',i);
            end               
            app.UITable_msweep.Data=data_s;

        end

        % Value changed function: PlotMaximumEnvelopeCheckBox
        function PlotMaximumEnvelopeCheckBoxValueChanged(app, event)
            plot_max(app);
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            ClosePlots(app);

            app.num = str2num(app.NumberListBox.Value); 
            N=app.num;

            A=app.UITable.Data;
            % A=table2cell(A);

            k=N+1;
            
            for i=1:N
                array{i}=char(A{k}); k=k+1;
            end

            for i=1:N
                leg{i}=char(A{k}); k=k+1;
            end
            for i=1:N
                pp{i}=A{k}; k=k+1;
            end


            freq=zeros(20000,app.num);
            PV=zeros(20000,app.num);
            arows=zeros(app.num,1);

            
            iflag=0;
            
            for i=1:N
            
                leg{i}=strrep(leg{i},'_',' ');
                
                if(str2num(pp{i})==1)
                    
                    iflag=1;
                    
                    try
                        THM=evalin('base',strtrim(array{i}));
                        sz=size(THM);
                        arows(i)=sz(1);
                        freq(1:sz(1),i)=THM(:,1);
                        PV(1:sz(1),i)=THM(:,2);                       
                    catch
                        ss=sprintf('%s read failed',array{i});
                        warndlg(ss);
                        return;
                    end
                end
            end

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            iunit = find(strcmp(Items, im));


            fig_num=1;

            if(app.pmax==1 && iflag==1)
                if(min(arows)~=max(arows))
                    warndlg('Natural Frequency columns must be the same.');
                    return;
                else
                    freq(:,end+1)=freq(:,1);

                    for i=1:arows(1)
                        PV(i,app.num+1)=max(PV(i,1:app.num));
                    end

                end 

                arows(app.num+1)=arows(1);
                app.num=app.num+1;
                leg{app.num}='Max Envelope';

                output_name=app.MaxEnvelopeOutputNameEditField.Value;
                assignin('base', output_name, [freq(:,1) PV(:,end)] );

                leg={leg{app.num} leg{1:app.num-1}};
                PV=[PV(:,app.num) PV(:,1:app.num-1)];

            end

            if(iflag==0)
                msgbox('No arrays selected for plotting');
            else    
                tstring=app.TitleEditField.Value;
                tstring=sprintf('%s \n\n',tstring);
                [fig_num,h]=srs_tripartite_function_alt(iunit,fig_num,freq,PV,arows,leg,tstring);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SRSTripartiteUIFigure = uifigure('Visible', 'off');
            app.SRSTripartiteUIFigure.Name = 'SRS Tripartite';
            app.ReturnButton = uibutton(app.SRSTripartiteUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.srs_tripartite_plotver10byTomIrvineLabel = uilabel(app.SRSTripartiteUIFigure);
            app.srs_tripartite_plotver10byTomIrvineLabel.Text = 'srs_tripartite_plot    ver 1.0  by Tom Irvine        This function plots multiple pseudo velocity SRS curves.';
            app.NumberListBoxLabel = uilabel(app.SRSTripartiteUIFigure);
            app.NumberListBoxLabel.Text = 'Number';
            app.NumberListBox = uilistbox(app.SRSTripartiteUIFigure);
            app.NumberListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberListBox.ValueChangedFcn = createCallbackFcn(app, @NumberListBoxValueChanged, true);
            app.NumberListBox.Value = '1';
            app.UITable = uitable(app.SRSTripartiteUIFigure);
            app.UITable.ColumnName = {'No.'; 'Array Name'; 'Legend'; 'Plot'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.PlotButton = uibutton(app.SRSTripartiteUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotMaximumEnvelopeCheckBox = uicheckbox(app.SRSTripartiteUIFigure);
            app.PlotMaximumEnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotMaximumEnvelopeCheckBoxValueChanged, true);
            app.PlotMaximumEnvelopeCheckBox.Text = 'Plot Maximum Envelope';
            app.MaxEnvelopeOutputNameEditFieldLabel = uilabel(app.SRSTripartiteUIFigure);
            app.MaxEnvelopeOutputNameEditFieldLabel.Text = 'Max Envelope Output Name';
            app.MaxEnvelopeOutputNameEditField = uieditfield(app.SRSTripartiteUIFigure, 'text');
            app.MaxEnvelopeOutputNameEditField.Value = 'max_envelope';
            app.TitleEditFieldLabel = uilabel(app.SRSTripartiteUIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.SRSTripartiteUIFigure, 'text');
            app.TitleEditField.Value = 'Pseudo Velocity  SRS  Q=10';
            app.ResetButton = uibutton(app.SRSTripartiteUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DeleteRowBelowButton = uibutton(app.SRSTripartiteUIFigure, 'push');
            app.DeleteRowBelowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowBelowButtonPushed, true);
            app.DeleteRowBelowButton.Text = 'Delete Row Below';
            app.RowListBoxLabel = uilabel(app.SRSTripartiteUIFigure);
            app.RowListBoxLabel.Text = 'Row';
            app.RowListBox = uilistbox(app.SRSTripartiteUIFigure);
            app.RowListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.RowListBox.Value = '10';
            app.srs_tripartite_plotver10byTomIrvineLabel_2 = uilabel(app.SRSTripartiteUIFigure);
            app.srs_tripartite_plotver10byTomIrvineLabel_2.Text = 'Each array must have two columns:  natural frequency (Hz) & pseudo velocity';
            app.srs_tripartite_plotver10byTomIrvineLabel_3 = uilabel(app.SRSTripartiteUIFigure);
            app.srs_tripartite_plotver10byTomIrvineLabel_3.Text = 'Each array must have the same natural frequency column for the Plot Maximum Envelope option.';
            app.UnitsListBoxLabel = uilabel(app.SRSTripartiteUIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SRSTripartiteUIFigure);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm'};
            app.UnitsListBox.Value = 'G, in/sec, in';
            % Show the figure after all components are created
            app.SRSTripartiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = srs_tripartite_plot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SRSTripartiteUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SRSTripartiteUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SRSTripartiteUIFigure)
        end
    end
end