classdef statistical_distributions_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectAnalysisListBox          matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel     matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        aTab                           matlab.ui.container.Tab
        CalculateButton_3              matlab.ui.control.Button
        UpperLimitz2EditField          matlab.ui.control.EditField
        UpperLimitz2EditFieldLabel     matlab.ui.control.Label
        LowerLimitz1EditField          matlab.ui.control.EditField
        LowerLimitz1EditFieldLabel     matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        bTab                           matlab.ui.container.Tab
        LowerLimitEditField            matlab.ui.control.EditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.EditField
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        cTab                           matlab.ui.container.Tab
        CalculateButton_Poisson        matlab.ui.control.Button
        RandomVariableXintegerLabel    matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.EditField
        UpperEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.EditField
        LowerEditFieldLabel            matlab.ui.control.Label
        MeanmuEditField                matlab.ui.control.EditField
        MeanmuEditFieldLabel           matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        dTab_2                         matlab.ui.container.Tab
        ToleranceFactorLabel           matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        NumberofSamplesEditField       matlab.ui.control.EditField
        NumberofSamplesEditFieldLabel  matlab.ui.control.Label
        ConfidenceEditField            matlab.ui.control.EditField
        ConfidenceEditFieldLabel       matlab.ui.control.Label
        ProbabilityEditField           matlab.ui.control.EditField
        ProbabilityLabel               matlab.ui.control.Label
        eTab                           matlab.ui.container.Tab
        UpperEditField_exp             matlab.ui.control.EditField
        UpperEditField_2Label          matlab.ui.control.Label
        LowerEditField_exp             matlab.ui.control.EditField
        LowerEditField_2Label          matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.EditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        CalculateButton_Exponential    matlab.ui.control.Button
        RandomVariableXLabel           matlab.ui.control.Label
        ExponentialDistributionLabel   matlab.ui.control.Label
        fTab                           matlab.ui.container.Tab
        ViewEquationButton             matlab.ui.control.Button
        PlotWeButton                   matlab.ui.control.Button
        CalculateButton_We             matlab.ui.control.Button
        UpperEditField_We              matlab.ui.control.EditField
        UpperEditField_2Label_2        matlab.ui.control.Label
        LowerEditField_We              matlab.ui.control.EditField
        LowerEditField_2Label_2        matlab.ui.control.Label
        RandomVariableXLabel_2         matlab.ui.control.Label
        ScalebetaEditField             matlab.ui.control.EditField
        ScalebetaEditFieldLabel        matlab.ui.control.Label
        ShapealphaEditField            matlab.ui.control.EditField
        ShapealphaEditFieldLabel       matlab.ui.control.Label
        WeibullTwoparameterLabel       matlab.ui.control.Label
        gTab                           matlab.ui.container.Tab
        DescriptiveStatistics1DArrayLabel  matlab.ui.control.Label
        statistical_distributions_ahver11byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end
        function AnalysisChange(app)
            clear(app);

            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            app.TabGroup.SelectedTab=app.TabGroup.Children(n);   
 
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear(app);
            AnalysisChange(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changing function: UpperLimitEditField
        function UpperLimitEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            z1=str2num(app.LowerLimitEditField.Value);
            z2=str2num(app.UpperLimitEditField.Value);

            p1=1-exp(-z1^2/2);
            q1=p1;
            w1=1-q1;

            p2=1-exp(-z2^2/2);
            q2=p2;
            w2=1-q2;

            q=q2-q1;
            w=1-q;
            
            s1=sprintf('Probability within limits \n   %12.8g \n\n Probability of exceeding limits \n   %12.8g',q,w);
           
            app.ResultsTextArea.Value=s1;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
        end

        % Value changing function: ProbabilityEditField
        function ProbabilityEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: ConfidenceEditField
        function ConfidenceEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: NumberofSamplesEditField
        function NumberofSamplesEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)

            p=str2num(app.ProbabilityEditField.Value);
            c=str2num(app.ConfidenceEditField.Value);
            nsamples=str2num(app.NumberofSamplesEditField.Value);
            
            if(nsamples<2)
                warndlg('At least 2 samples required');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [k,Zp,delta,T]=k_factor(p,c,nsamples);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % fprintf('\n Zp=%8.4g  delta=%8.4g  T=%8.4g \n',Zp,delta,T);
          
            sss=sprintf(' P%g/%g Tolerance factor k = %8.4g',p,c,k);

            app.ResultsTextArea.Value=sss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
            
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            z1=str2num(app.LowerLimitz1EditField.Value);
            z2=str2num(app.UpperLimitz2EditField.Value);
            
            normal_cdf=@(x)( 1/2*erfc(-x/sqrt(2)));
            %
            p1 = normal_cdf(z1);
            p2 = normal_cdf(z2);
            %
            q=p2-p1;
            w=1-q;
            %
            
            s1=sprintf('Probability within limits \n   %12.8g \n\n Probability of exceeding limits \n   %12.8g',q,w);
            %
            app.ResultsTextArea.Value=s1;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
                     
        end

        % Value changing function: LowerLimitz1EditField
        function LowerLimitz1EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: UpperLimitz2EditField
        function UpperLimitz2EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: LowerLimitEditField
        function LowerLimitEditFieldValueChanging(app, event)

            clear(app);
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)

            AnalysisChange(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            clear(app);

            m=app.TabGroup.SelectedTab;
            n=m.Title;

            if(strcmp(n,'a'))
                n=1;
            end              
            if(strcmp(n,'b'))
                n=2;
            end     
            if(strcmp(n,'c'))
                n=3;
            end              
            if(strcmp(n,'d'))
                n=4;
            end 
            if(strcmp(n,'e'))
                n=5;
            end              
            if(strcmp(n,'f'))
                n=6;
            end     
            if(strcmp(n,'g'))
                n=7;
            end              
            if(strcmp(n,'h'))
                n=8;
            end   
            if(strcmp(n,'i'))
                n=9;
            end      
            if(strcmp(n,'j'))
                n=10;
            end              
            if(strcmp(n,'k'))
                n=11;
            end  
            if(strcmp(n,'l'))
                n=12;
            end           
            if(strcmp(n,'m'))
                n=13;
            end            

            app.SelectAnalysisListBox.Value=app.SelectAnalysisListBox.Items(n);
            
        end

        % Button pushed function: CalculateButton_Poisson
        function CalculateButton_PoissonPushed(app, event)
            mu=str2num(app.MeanmuEditField.Value);
            X1=str2num(app.LowerEditField.Value);
            X2=str2num(app.UpperEditField.Value);
        %
            FXR=0;    
            for i=X1:X2
                a=exp(-mu)*(mu^i)/factorial(i);
                FXR=FXR+a;
            end   
        %
            q=FXR;
        %
            w=1-q;
                
            s1=sprintf('Probability = %8.4g \n\n 1-Probability = %8.4g',q,w);
        %
            app.ResultsTextArea.Value=s1;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button pushed function: CalculateButton_Exponential
        function CalculateButton_ExponentialPushed(app, event)
           mu=str2num(app.lambdaEditField.Value);

            if(mu<=0)
                warndlg('lambda must be > 0');
                return;
            end    

            lambda=1/mu;
            X1=str2num(app.LowerEditField_exp.Value);
            X2=str2num(app.UpperEditField_exp.Value);
        %
            q=abs(exp(-lambda*X1)-exp(-lambda*X2));
        %
        %
            w=1-q;

            MTBF=mu;
                
            s1=sprintf('Probability = %12.8g \n\n 1-Probability = %12.8g \n\n Failure Rate = %12.8g \n MTBF = %g ',q,w,lambda,MTBF);
    
        %
            app.ResultsTextArea.Value=s1;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
        end

        % Value changing function: LowerEditField
        function LowerEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: UpperEditField
        function UpperEditFieldValueChanging(app, event)
      
            clear(app);
        end

        % Value changing function: MeanmuEditField
        function MeanmuEditFieldValueChanging(app, event)
          
            clear(app);
        end

        % Value changing function: lambdaEditField
        function lambdaEditFieldValueChanging(app, event)
         
            clear(app);
        end

        % Value changing function: LowerEditField_exp
        function LowerEditField_expValueChanging(app, event)
            
            clear(app);
        end

        % Value changing function: UpperEditField_exp
        function UpperEditField_expValueChanging(app, event)
            
            clear(app);
        end

        % Value changing function: LowerEditField_We
        function LowerEditField_WeValueChanging(app, event)
            
            clear(app);
        end

        % Value changing function: UpperEditField_We
        function UpperEditField_WeValueChanging(app, event)
          
            clear(app);
        end

        % Value changing function: ShapealphaEditField
        function ShapealphaEditFieldValueChanging(app, event)
            
            clear(app);
        end

        % Value changing function: ScalebetaEditField
        function ScalebetaEditFieldValueChanging(app, event)
            
            clear(app);
        end

        % Button pushed function: CalculateButton_We
        function CalculateButton_WePushed(app, event)
            X1=str2num(app.LowerEditField_We.Value);
            X2=str2num(app.UpperEditField_We.Value);

            alpha=str2num(app.ShapealphaEditField.Value);
            beta=str2num(app.ScalebetaEditField.Value);

            if(alpha<=0)
               
                warndlg(' alpha must be > 0 ');
                return;
            end
            if(beta<=0)
               
                warndlg(' beta must be > 0 ');
                return;
            end
            
            q=abs( exp(-(X1/beta)^alpha) - exp(-(X2/beta)^alpha) );

            w=1-q;
    
            s1=sprintf('Probability = %12.8g \n\n 1-Probability = %12.8g ',q,w);
  
            app.ResultsTextArea.Value=s1;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            A = imread('Weibull.jpg');
            figure(99)     
            imshow(A,'border','tight','InitialMagnification',100);
        end

        % Button pushed function: PlotWeButton
        function PlotWeButtonPushed(app, event)
            alpha=str2num(app.ShapealphaEditField.Value);
            beta=str2num(app.ScalebetaEditField.Value);

            if(alpha<=0)
               
                warndlg(' alpha must be > 0 ');
                return;
            end
            if(beta<=0)
               
                warndlg(' beta must be > 0 ');
                return;
            end
            
            
            xmax= 1.3*beta*(-log(0.01))^(1/alpha);

            n=1000;
            
            dx=xmax/n;
            
            xx=zeros(n,1);
            yy=zeros(n,1);
            
            KL=alpha/beta;
            
            for i=1:n
                
                x=(i-1)*dx;
                xx(i)=x;
                
                xL=x/beta;
                
                yy(i)=KL*xL^(alpha-1)*exp(-(xL^alpha));
                
            end    
            
            
            sss=sprintf('Weibull Probability Density Function \n alpha=%g  beta=%g ',alpha,beta);
            
            figure(1);
            plot(xx,yy);
            title(sss);
            grid on;
            xlabel('x');
            ylabel('f(x;k,beta)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.statistical_distributions_ahver11byTomIrvineLabel = uilabel(app.UIFigure);
            app.statistical_distributions_ahver11byTomIrvineLabel.Text = 'statistical_distributions_ah      ver 1.1  by Tom Irvine';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.aTab = uitab(app.TabGroup);
            app.aTab.Title = 'a';
            app.Label_2 = uilabel(app.aTab);
            app.Label_2.Text = {'Normal Distribution.          standard score:     z = ( x - mean ) / (std dev)  '; ''; 'This script integrates the normal distribution curve to find the probability '; 'between two limits.     '; ''};
            app.LowerLimitz1EditFieldLabel = uilabel(app.aTab);
            app.LowerLimitz1EditFieldLabel.Text = 'Lower Limit  z1';
            app.LowerLimitz1EditField = uieditfield(app.aTab, 'text');
            app.LowerLimitz1EditField.ValueChangingFcn = createCallbackFcn(app, @LowerLimitz1EditFieldValueChanging, true);
            app.UpperLimitz2EditFieldLabel = uilabel(app.aTab);
            app.UpperLimitz2EditFieldLabel.Text = 'Upper Limit  z2';
            app.UpperLimitz2EditField = uieditfield(app.aTab, 'text');
            app.UpperLimitz2EditField.ValueChangingFcn = createCallbackFcn(app, @UpperLimitz2EditFieldValueChanging, true);
            app.CalculateButton_3 = uibutton(app.aTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.bTab = uitab(app.TabGroup);
            app.bTab.Title = 'b';
            app.CalculateButton = uibutton(app.bTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.bTab);
            app.Label.Text = {'This script integrates the Rayleigh distribution curve to find    '; 'the probability within limits.   '; ''; 'The Upper Limit is the scale factor multiplied by 1-sigma.'};
            app.UpperLimitEditFieldLabel = uilabel(app.bTab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.bTab, 'text');
            app.UpperLimitEditField.ValueChangingFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanging, true);
            app.LowerLimitEditFieldLabel = uilabel(app.bTab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.bTab, 'text');
            app.LowerLimitEditField.ValueChangingFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanging, true);
            app.LowerLimitEditField.Value = '0';
            app.cTab = uitab(app.TabGroup);
            app.cTab.Title = 'c';
            app.Label_3 = uilabel(app.cTab);
            app.Label_3.Text = {'The Poisson distribution gives the probability of a random variable X where the '; 'number of successes occurs during a given time interval or in a specified region.'};
            app.MeanmuEditFieldLabel = uilabel(app.cTab);
            app.MeanmuEditFieldLabel.Text = 'Mean mu';
            app.MeanmuEditField = uieditfield(app.cTab, 'text');
            app.MeanmuEditField.ValueChangingFcn = createCallbackFcn(app, @MeanmuEditFieldValueChanging, true);
            app.LowerEditFieldLabel = uilabel(app.cTab);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.cTab, 'text');
            app.LowerEditField.ValueChangingFcn = createCallbackFcn(app, @LowerEditFieldValueChanging, true);
            app.LowerEditField.Value = '0';
            app.UpperEditFieldLabel = uilabel(app.cTab);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.cTab, 'text');
            app.UpperEditField.ValueChangingFcn = createCallbackFcn(app, @UpperEditFieldValueChanging, true);
            app.RandomVariableXintegerLabel = uilabel(app.cTab);
            app.RandomVariableXintegerLabel.Text = 'Random Variable X (integer)';
            app.CalculateButton_Poisson = uibutton(app.cTab, 'push');
            app.CalculateButton_Poisson.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PoissonPushed, true);
            app.CalculateButton_Poisson.Text = 'Calculate';
            app.dTab_2 = uitab(app.TabGroup);
            app.dTab_2.Title = 'd';
            app.ProbabilityLabel = uilabel(app.dTab_2);
            app.ProbabilityLabel.Text = 'Probability %';
            app.ProbabilityEditField = uieditfield(app.dTab_2, 'text');
            app.ProbabilityEditField.ValueChangingFcn = createCallbackFcn(app, @ProbabilityEditFieldValueChanging, true);
            app.ProbabilityEditField.Value = '95';
            app.ConfidenceEditFieldLabel = uilabel(app.dTab_2);
            app.ConfidenceEditFieldLabel.Text = 'Confidence %';
            app.ConfidenceEditField = uieditfield(app.dTab_2, 'text');
            app.ConfidenceEditField.ValueChangingFcn = createCallbackFcn(app, @ConfidenceEditFieldValueChanging, true);
            app.ConfidenceEditField.Value = '50';
            app.NumberofSamplesEditFieldLabel = uilabel(app.dTab_2);
            app.NumberofSamplesEditFieldLabel.Text = 'Number of Samples';
            app.NumberofSamplesEditField = uieditfield(app.dTab_2, 'text');
            app.NumberofSamplesEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofSamplesEditFieldValueChanging, true);
            app.NumberofSamplesEditField.Value = '3';
            app.CalculateButton_2 = uibutton(app.dTab_2, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ToleranceFactorLabel = uilabel(app.dTab_2);
            app.ToleranceFactorLabel.Text = 'Tolerance Factor';
            app.eTab = uitab(app.TabGroup);
            app.eTab.Title = 'e';
            app.ExponentialDistributionLabel = uilabel(app.eTab);
            app.ExponentialDistributionLabel.Text = 'Exponential Distribution';
            app.RandomVariableXLabel = uilabel(app.eTab);
            app.RandomVariableXLabel.Text = 'Random Variable X';
            app.CalculateButton_Exponential = uibutton(app.eTab, 'push');
            app.CalculateButton_Exponential.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_ExponentialPushed, true);
            app.CalculateButton_Exponential.Text = 'Calculate';
            app.lambdaEditFieldLabel = uilabel(app.eTab);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.eTab, 'text');
            app.lambdaEditField.ValueChangingFcn = createCallbackFcn(app, @lambdaEditFieldValueChanging, true);
            app.LowerEditField_2Label = uilabel(app.eTab);
            app.LowerEditField_2Label.Text = 'Lower';
            app.LowerEditField_exp = uieditfield(app.eTab, 'text');
            app.LowerEditField_exp.ValueChangingFcn = createCallbackFcn(app, @LowerEditField_expValueChanging, true);
            app.LowerEditField_exp.Value = '0';
            app.UpperEditField_2Label = uilabel(app.eTab);
            app.UpperEditField_2Label.Text = 'Upper';
            app.UpperEditField_exp = uieditfield(app.eTab, 'text');
            app.UpperEditField_exp.ValueChangingFcn = createCallbackFcn(app, @UpperEditField_expValueChanging, true);
            app.fTab = uitab(app.TabGroup);
            app.fTab.Title = 'f';
            app.WeibullTwoparameterLabel = uilabel(app.fTab);
            app.WeibullTwoparameterLabel.Text = 'Weibull, Two-parameter';
            app.ShapealphaEditFieldLabel = uilabel(app.fTab);
            app.ShapealphaEditFieldLabel.Text = 'Shape alpha';
            app.ShapealphaEditField = uieditfield(app.fTab, 'text');
            app.ShapealphaEditField.ValueChangingFcn = createCallbackFcn(app, @ShapealphaEditFieldValueChanging, true);
            app.ScalebetaEditFieldLabel = uilabel(app.fTab);
            app.ScalebetaEditFieldLabel.Text = 'Scale beta';
            app.ScalebetaEditField = uieditfield(app.fTab, 'text');
            app.ScalebetaEditField.ValueChangingFcn = createCallbackFcn(app, @ScalebetaEditFieldValueChanging, true);
            app.RandomVariableXLabel_2 = uilabel(app.fTab);
            app.RandomVariableXLabel_2.Text = 'Random Variable X';
            app.LowerEditField_2Label_2 = uilabel(app.fTab);
            app.LowerEditField_2Label_2.Text = 'Lower';
            app.LowerEditField_We = uieditfield(app.fTab, 'text');
            app.LowerEditField_We.ValueChangingFcn = createCallbackFcn(app, @LowerEditField_WeValueChanging, true);
            app.LowerEditField_We.Value = '0';
            app.UpperEditField_2Label_2 = uilabel(app.fTab);
            app.UpperEditField_2Label_2.Text = 'Upper';
            app.UpperEditField_We = uieditfield(app.fTab, 'text');
            app.UpperEditField_We.ValueChangingFcn = createCallbackFcn(app, @UpperEditField_WeValueChanging, true);
            app.CalculateButton_We = uibutton(app.fTab, 'push');
            app.CalculateButton_We.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_WePushed, true);
            app.CalculateButton_We.Text = 'Calculate';
            app.PlotWeButton = uibutton(app.fTab, 'push');
            app.PlotWeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWeButtonPushed, true);
            app.PlotWeButton.Text = 'Plot';
            app.ViewEquationButton = uibutton(app.fTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.gTab = uitab(app.TabGroup);
            app.gTab.Title = 'g';
            app.DescriptiveStatistics1DArrayLabel = uilabel(app.gTab);
            app.DescriptiveStatistics1DArrayLabel.Text = 'Descriptive Statistics, 1D Array';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SelectAnalysisListBoxLabel = uilabel(app.UIFigure);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.UIFigure);
            app.SelectAnalysisListBox.Items = {'Normal          ', 'Rayleigh        ', 'Poisson         ', 'Tolerance Factor', 'Exponential', 'Weibull, Two-parameter'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.SelectAnalysisListBox.Value = 'Weibull, Two-parameter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = statistical_distributions_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end