classdef steady_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        pushbutton_return           matlab.ui.control.Button
        pushbutton_calculate        matlab.ui.control.Button
        uipanel9                    matlab.ui.container.Panel
        FrequencyRatioffnEditField  matlab.ui.control.NumericEditField
        FrequencyRatioffnEditFieldLabel  matlab.ui.control.Label
        StiffnessEditField          matlab.ui.control.NumericEditField
        StiffnessEditFieldLabel     matlab.ui.control.Label
        text_phase                  matlab.ui.control.Label
        edit_phase                  matlab.ui.control.EditField
        text_tf_or_rd               matlab.ui.control.Label
        text_displacement           matlab.ui.control.Label
        text_velocity               matlab.ui.control.Label
        text_acceleration           matlab.ui.control.Label
        edit_tf_or_rd               matlab.ui.control.EditField
        edit_displacement           matlab.ui.control.EditField
        edit_velocity               matlab.ui.control.EditField
        edit_acceleration           matlab.ui.control.EditField
        text2                       matlab.ui.control.Label
        uipanel1                    matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        edit_mass                   matlab.ui.control.EditField
        text_enter_mass             matlab.ui.control.Label
        edit_accel_force            matlab.ui.control.EditField
        text_force_base             matlab.ui.control.Label
        edit_fext                   matlab.ui.control.EditField
        text13                      matlab.ui.control.Label
        edit_fn                     matlab.ui.control.EditField
        text12                      matlab.ui.control.Label
        edit_Q                      matlab.ui.control.EditField
        text11                      matlab.ui.control.Label
        listbox_excitation          matlab.ui.control.ListBox
        text10                      matlab.ui.control.Label
        text9                       matlab.ui.control.Label
        listbox_units               matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function change_frames(app, hObject, eventdata, handles)
            %
            nu=get(handles.listbox_units,'Value');
            ne=get(handles.listbox_excitation,'Value');
            
            clear_results(app)
            
            
            if(ne==1)
                app.text_enter_mass.Visible='on';
                app.edit_mass.Visible='on';
                app.Image.ImageSource='sdof_force.jpg';                
            else
                app.text_enter_mass.Visible='off';
                app.edit_mass.Visible='off';
                app.Image.ImageSource='sdof_base.jpg';
            end
            
            if(nu==1) % English
                if(ne==1)
                   sss=sprintf('Applied Force (lbf)');
                   app.text_enter_mass.Text='Enter Mass (lbm)';
                   app.StiffnessEditFieldLabel.Text='Stiffness (lbf/in)';
                else
                   sss=sprintf('Base Acceleration (G)');
                   app.StiffnessEditFieldLabel.Text='Stiffness (N/m)';                
                end
            %
            else % metric
            %
                if(ne==1)
                   sss=sprintf('Applied Force (N)');
                   app.text_enter_mass.Text='Mass (kg)';
                else
                   sss=sprintf('Base Acceleration (G)');
                end
            end
            %
            app.text_force_base.Text=sss;


        end
        
        function clear_results(app)
            %
            app.FrequencyRatioffnEditField.Visible='off';
            app.FrequencyRatioffnEditFieldLabel.Visible='off';           
            
            app.StiffnessEditField.Visible='off';
            app.StiffnessEditFieldLabel.Visible='off';

            app.edit_phase.Visible='off';
            app.text_phase.Visible='off';
            
            app.text_acceleration.Text=' ';
            app.text_velocity.Text=' ';
            app.text_displacement.Text=' ';
            app.text_tf_or_rd.Text=' ';
            
            app.edit_acceleration.Visible='off';
            app.edit_velocity.Visible='off';
            app.edit_displacement.Visible='off';
            app.edit_tf_or_rd.Visible='off';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function steady_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to steady (see VARARGIN)
            
            % Choose default command line output for steady
            handles.output = hObject;
            
            app.text_enter_mass.Visible='on';
            app.edit_mass.Visible='on';
            
            
            clear_results(app)
            
            change_frames(app, hObject, eventdata, handles);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_fext, listbox_excitation
        function listbox_excitation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_excitation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_excitation contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_excitation
            change_frames(app, hObject, eventdata, handles);
             clear_results(app)
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_units contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_units
            
            change_frames(app, hObject, eventdata, handles);            
            clear_results(app)

        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            tpi=2*pi;
            
            nu=get(handles.listbox_units,'Value');
            ne=get(handles.listbox_excitation,'Value');
            
            
            app.edit_acceleration.Visible='on';
            app.edit_velocity.Visible='on';
            app.edit_displacement.Visible='on';
            app.edit_tf_or_rd.Visible='on';
            
            app.text_acceleration.Text='Acceleration (G)';
            
            if(nu==1)
                app.text_velocity.Text='Velocity (in/sec)';
                app.text_displacement.Text='Displacement (in)';
            
                if(ne==1)
                    app.text_tf_or_rd.Text='Transmitted Force (lbf)';
                else
                    app.text_tf_or_rd.Text='Relative Displacement (in)';
                end
            else
                app.text_velocity.Text='Velocity (m/sec)';
                app.text_displacement.Text='Displacement (mm)';
            
                if(ne==1)
                    app.text_tf_or_rd.Text='Transmitted Force (N)';
                else
                    app.text_tf_or_rd.Text='Relative Displacement (mm)';
                end
            
                app.edit_phase.Visible='on';
                app.text_phase.Visible='on';
            
            end
            
            fn=str2num(get(handles.edit_fn,'String'));
            Q=str2num(get(handles.edit_Q,'String'));
            
            fext=str2num(get(handles.edit_fext,'String'));
            
            omegan=tpi*fn;
            omega=tpi*fext;
            
            rho=omega/omegan;

            rho=str2double(sprintf('%g',rho));
            
            app.FrequencyRatioffnEditField.Value=rho;
            app.FrequencyRatioffnEditField.Visible='on';
            app.FrequencyRatioffnEditFieldLabel.Visible='on';           
            
            damp=1/(2*Q);
            
            den=omegan^2-omega^2+(1i)*(2*damp*omegan*omega);

            if(ne==1)
                app.StiffnessEditField.Visible='on';
                app.StiffnessEditFieldLabel.Visible='on';   
            else            
                app.StiffnessEditField.Visible='off';
                app.StiffnessEditFieldLabel.Visible='off';
            end
            
            
            if(ne==1)  % applied force
            
                force=str2num(get(handles.edit_accel_force,'String'));
                mass=str2num(get(handles.edit_mass,'String'));
            
                if(nu==1)
                    mass=mass/386;
                end
            
                stiff=mass*omegan^2;
            
                k=str2num(sprintf('%8.4g',stiff)); 

                app.StiffnessEditField.Value=k;     
            
                displacement=force*(1/stiff)*omegan^2/den;
                velocity=(1i)*omega*displacement;
                acceleration=-omega^2*displacement;
            
                term=2*damp*rho;
            
                num=sqrt(1+term^2);
                den=sqrt((1-rho^2)^2+term^2);
            
                transmitted_force=force*num/den;
            
            else       % base excitation
            
                base_accel=str2num(get(handles.edit_accel_force,'String'));
            
                if(nu==1)
                    base_accel=base_accel*386;
                else
                    base_accel=base_accel*9.81;
                end
            
            
                num=omegan^2+(1i)*2*damp*omega*omegan;
            
                acceleration=base_accel*num/den;
                velocity=acceleration/((1i)*omega);
                displacement=acceleration/(-omega^2);
                relative_displacement=-base_accel/den;
            
                ph=phase(num/den);
            
                sss=sprintf('%8.4g',180*ph/pi);
                app.edit_phase.Value=sss;
            
                app.edit_phase.Visible='on';
                app.text_phase.Visible='on';
            
            end
            
            if(nu==1)
                acceleration=acceleration/386;
            else
                acceleration=acceleration/9.81;
                displacement=displacement*1000;
                if(ne==2)
                   relative_displacement=relative_displacement*1000;
                end
            end
            
            as=sprintf('%8.4g',abs(acceleration));
            app.edit_acceleration.Value=as;
            
            vs=sprintf('%8.4g',abs(velocity));
            app.edit_velocity.Value=vs;
            
            ds=sprintf('%8.4g',abs(displacement));
            app.edit_displacement.Value=ds;
            
            if(ne==1)
                tf=sprintf('%8.4g',transmitted_force);
                app.edit_tf_or_rd.Value=tf;
            else
                rd=sprintf('%8.4g',abs(relative_displacement));
                app.edit_tf_or_rd.Value=rd;
            end

        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Value changing function: edit_fext
        function edit_fextValueChanging(app, event)
            clear_results(app)
        end

        % Value changing function: edit_accel_force
        function edit_accel_forceValueChanging(app, event)
            clear_results(app)
        end

        % Value changing function: edit_fn
        function edit_fnValueChanging(app, event)
             clear_results(app)
        end

        % Value changing function: edit_Q
        function edit_QValueChanging(app, event)
            clear_results(app)            
        end

        % Value changing function: edit_mass
        function edit_massValueChanging(app, event)
            clear_results(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'steady';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Enter Data';
            app.uipanel1.Tag = 'uipanel1';
            app.listbox_units = uilistbox(app.uipanel1);
            app.listbox_units.Items = {'English', 'metric'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'English';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Unit';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Excitation';
            app.listbox_excitation = uilistbox(app.uipanel1);
            app.listbox_excitation.Items = {'Applied Force', 'Base Excitation'};
            app.listbox_excitation.ValueChangedFcn = createCallbackFcn(app, @listbox_excitation_Callback, true);
            app.listbox_excitation.Tag = 'listbox_excitation';
            app.listbox_excitation.Value = 'Applied Force';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Amplification Factor Q';
            app.edit_Q = uieditfield(app.uipanel1, 'text');
            app.edit_Q.ValueChangingFcn = createCallbackFcn(app, @edit_QValueChanging, true);
            app.edit_Q.Tag = 'edit_Q';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Natural Frequency (Hz)';
            app.edit_fn = uieditfield(app.uipanel1, 'text');
            app.edit_fn.ValueChangingFcn = createCallbackFcn(app, @edit_fnValueChanging, true);
            app.edit_fn.Tag = 'edit_fn';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Excitation Frequency (Hz)';
            app.edit_fext = uieditfield(app.uipanel1, 'text');
            app.edit_fext.ValueChangedFcn = createCallbackFcn(app, @listbox_excitation_Callback, true);
            app.edit_fext.ValueChangingFcn = createCallbackFcn(app, @edit_fextValueChanging, true);
            app.edit_fext.Tag = 'edit_fext';
            app.text_force_base = uilabel(app.uipanel1);
            app.text_force_base.Tag = 'text_force_base';
            app.text_force_base.Text = 'Applied Force (lbf)';
            app.edit_accel_force = uieditfield(app.uipanel1, 'text');
            app.edit_accel_force.ValueChangingFcn = createCallbackFcn(app, @edit_accel_forceValueChanging, true);
            app.edit_accel_force.Tag = 'edit_accel_force';
            app.text_enter_mass = uilabel(app.uipanel1);
            app.text_enter_mass.Tag = 'text_enter_mass';
            app.text_enter_mass.Text = 'Mass (lbm)';
            app.edit_mass = uieditfield(app.uipanel1, 'text');
            app.edit_mass.ValueChangingFcn = createCallbackFcn(app, @edit_massValueChanging, true);
            app.edit_mass.Tag = 'edit_mass';
            app.Image = uiimage(app.uipanel1);
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'steady_App_1.m  ver 1.3  by Tom Irvine      Steady-state Response of a Single-degree-of-freedom System Subjected to Sinusoidal Excitation';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Results';
            app.uipanel9.Tag = 'uipanel9';
            app.edit_acceleration = uieditfield(app.uipanel9, 'text');
            app.edit_acceleration.Tag = 'edit_acceleration';
            app.edit_velocity = uieditfield(app.uipanel9, 'text');
            app.edit_velocity.Tag = 'edit_velocity';
            app.edit_displacement = uieditfield(app.uipanel9, 'text');
            app.edit_displacement.Tag = 'edit_displacement';
            app.edit_tf_or_rd = uieditfield(app.uipanel9, 'text');
            app.edit_tf_or_rd.Tag = 'edit_tf_or_rd';
            app.text_acceleration = uilabel(app.uipanel9);
            app.text_acceleration.Tag = 'text_acceleration';
            app.text_acceleration.Text = 'Acceleration (G)';
            app.text_velocity = uilabel(app.uipanel9);
            app.text_velocity.Tag = 'text_velocity';
            app.text_velocity.Text = 'Velocity';
            app.text_displacement = uilabel(app.uipanel9);
            app.text_displacement.Tag = 'text_displacement';
            app.text_displacement.Text = 'Displacement';
            app.text_tf_or_rd = uilabel(app.uipanel9);
            app.text_tf_or_rd.Tag = 'text_tf_or_rd';
            app.text_tf_or_rd.Text = 'Relative Displacement';
            app.edit_phase = uieditfield(app.uipanel9, 'text');
            app.edit_phase.Tag = 'edit_phase';
            app.text_phase = uilabel(app.uipanel9);
            app.text_phase.Tag = 'text_phase';
            app.text_phase.Text = 'Phase (deg)';
            app.StiffnessEditFieldLabel = uilabel(app.uipanel9);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.uipanel9, 'numeric');
            app.FrequencyRatioffnEditFieldLabel = uilabel(app.uipanel9);
            app.FrequencyRatioffnEditFieldLabel.Text = {'Frequency Ratio '; '( f / fn )'};
            app.FrequencyRatioffnEditField = uieditfield(app.uipanel9, 'numeric');
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = steady_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)steady_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end