classdef stress_from_acceleration_psd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StressfromAccelPSDUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        MaxFreqHzEditField            matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel       matlab.ui.control.Label
        MinFreqHzEditField            matlab.ui.control.EditField
        MinFreqHzEditFieldLabel       matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        ViewEquationButton            matlab.ui.control.Button
        UnitsListBox                  matlab.ui.control.ListBox
        UnitsListBoxLabel             matlab.ui.control.Label
        MassDensitylbmin3EditField    matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField    matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        SelectInputArrayListBox       matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton              matlab.ui.control.Button
        text1                         matlab.ui.control.Label
        listbox_method                matlab.ui.control.ListBox
        CalculateButton               matlab.ui.control.Button
        CoefficientKEditField         matlab.ui.control.EditField
        CoefficientKEditFieldLabel    matlab.ui.control.Label
        MaterialListBox               matlab.ui.control.ListBox
        MaterialListBoxLabel          matlab.ui.control.Label
        ExportTab                     matlab.ui.container.Tab
        ReturnButton_2                matlab.ui.control.Button
        EnteroutputarraynamesLabel    matlab.ui.control.Label
        StressPSDEditField            matlab.ui.control.EditField
        StressPSDEditFieldLabel       matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        VelocityPSDEditField          matlab.ui.control.EditField
        VelocityPSDEditFieldLabel     matlab.ui.control.Label
        stress_from_acceleration_psdver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM            % Description
        iu             %
        imat           %
        method         %
        velocity_psd   %
        stress_psd     %
    end
    
    methods (Access = private)
        
        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end
    
        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end

        
        function clear(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
        end    

       function ChangeMaterials(app)

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            E=elastic_modulus;
            rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';          
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)';                       
            end    
           
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.method=1;      
           clear(app); 
           ChangeMaterials(app); 
           update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear(app);

            fig_num=1;

            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            n=length(app.THM(:,1));
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end
            
            rho=str2num(app.MassDensitylbmin3EditField.Value);
            E=str2num(app.ElasticModuluspsiEditField.Value);
            chat=str2num(app.CoefficientKEditField.Value);

            if(app.iu==1)
                rho=rho/386;        
            else    
                [E]=GPa_to_Pa(E);        
            end    

            f=app.THM(:,1);
            a=app.THM(:,2);
            
            [~,arms] = calculate_PSD_slopes(f,a);
            
            omega=2*pi*f;
            
            Q2=(chat*sqrt(E*rho))^2;
            
            v=zeros(n,1);
            %      
            for i=1:n
                v(i)=a(i)/omega(i)^2;
            end
            
            if(app.iu==1)
                v=v*386^2;
                s=v*Q2/1000^2;  % ksi         
            else
                v=v*9.81^2;
                s=v*Q2;  % Pa         
            end    
            
            [~,vrms] = calculate_PSD_slopes(f,v);
            [~,srms] = calculate_PSD_slopes(f,s);
            
            xlabel2='Frequency (Hz)';
            nfont=11;
            
            data1=[f a];
            data2=[f v];
            
            t_string1=sprintf('Acceleration PSD  %7.3g GRMS Overall',arms);
            t_string2=sprintf('Velocity PSD  %7.3g (in/sec) RMS Overall',vrms);
            
            ylabel1='Accel (G^2/Hz)';
            ylabel2='Vel ((in/sec)^2/Hz))';
            
            fmin=str2num(app.MinFreqHzEditField.Value);
            fmax=str2num(app.MaxFreqHzEditField.Value);
            
            [fig_num,h2]=subplots_two_loglog_1x2_xlim_h2(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2,nfont,fmin,fmax);
            
            set(h2, 'Position', [0 0 1000 400]);
            
            md=6;
            
            t_string=sprintf('Stress PSD  %7.3g ksi RMS Overall',srms);
            x_label='Frequency (Hz)';
            y_label='Stress (ksi^2/Hz)';
            ppp=[f s];
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);            

            app.velocity_psd=data2;
            app.stress_psd=ppp;


        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            A = imread('SV_relationship.png');
            figure(999) 
            imshow(A,'border','tight','InitialMagnification',100) 
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ChangeMaterials(app);   
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ChangeMaterials(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Button pushed function: ReturnButton_2
        function ReturnButton_2Pushed(app, event)
            closereq;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            vname=app.VelocityPSDEditField.Value;
            sname=app.StressPSDEditField.Value;
            
            assignin('base', vname, app.velocity_psd);
            assignin('base', sname, app.stress_psd);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StressfromAccelPSDUIFigure = uifigure('Visible', 'off');
            app.StressfromAccelPSDUIFigure.Name = 'Stress from Accel PSD';
            app.stress_from_acceleration_psdver10byTomIrvineLabel = uilabel(app.StressfromAccelPSDUIFigure);
            app.stress_from_acceleration_psdver10byTomIrvineLabel.Text = {'stress_from_acceleration_psd    ver 1.1   by Tom Irvine        '; ''; 'This script calculates a velocity reponse PSD from an acceleration response PSD and then uses the stress-velocity relationship to calculate the overall stress.'; ''; 'The acceleration response PSD must have two columns:  freq (Hz) & accel (G^2/Hz)'};
            app.TabGroup = uitabgroup(app.StressfromAccelPSDUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MaterialListBoxLabel = uilabel(app.MainTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MainTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.CoefficientKEditFieldLabel = uilabel(app.MainTab);
            app.CoefficientKEditFieldLabel.Text = 'Coefficient K';
            app.CoefficientKEditField = uieditfield(app.MainTab, 'text');
            app.CoefficientKEditField.Value = '2';
            app.CalculateButton = uibutton(app.MainTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.listbox_method = uilistbox(app.MainTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.MainTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.MainTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.MainTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.MainTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MainTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MainTab, 'text');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MainTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MainTab, 'text');
            app.UnitsListBoxLabel = uilabel(app.MainTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.MainTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.ViewEquationButton = uibutton(app.MainTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.ReturnButton = uibutton(app.MainTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.MinFreqHzEditFieldLabel = uilabel(app.MainTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.MainTab, 'text');
            app.MaxFreqHzEditFieldLabel = uilabel(app.MainTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.MainTab, 'text');
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.VelocityPSDEditFieldLabel = uilabel(app.ExportTab);
            app.VelocityPSDEditFieldLabel.Text = 'Velocity PSD';
            app.VelocityPSDEditField = uieditfield(app.ExportTab, 'text');
            app.VelocityPSDEditField.Value = 'velocity_psd';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StressPSDEditFieldLabel = uilabel(app.ExportTab);
            app.StressPSDEditFieldLabel.Text = 'Stress PSD';
            app.StressPSDEditField = uieditfield(app.ExportTab, 'text');
            app.StressPSDEditField.Value = 'stress_psd';
            app.EnteroutputarraynamesLabel = uilabel(app.ExportTab);
            app.EnteroutputarraynamesLabel.Text = 'Enter output array names';
            app.ReturnButton_2 = uibutton(app.ExportTab, 'push');
            app.ReturnButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnButton_2Pushed, true);
            app.ReturnButton_2.Text = 'Return';
            % Show the figure after all components are created
            app.StressfromAccelPSDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stress_from_acceleration_psd

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StressfromAccelPSDUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.StressfromAccelPSDUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StressfromAccelPSDUIFigure)
        end
    end
end