classdef stress_psd_fatigue_Basquin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        stresspsdBasquinUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StressPSDTab                    matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        StressUnitListBox               matlab.ui.control.ListBox
        StressUnitListBoxLabel          matlab.ui.control.Label
        TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel  matlab.ui.control.Label
        MaterialTab                     matlab.ui.container.Tab
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        unitLabel                       matlab.ui.control.Label
        FatigueStrengthCoefficientAEditField  matlab.ui.control.EditField
        FatigueStrengthCoefficientAEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        FatigueExponentMEditField       matlab.ui.control.EditField
        FatigueExponentMEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        StressScaleFactorEditField      matlab.ui.control.EditField
        StressScaleFactorEditFieldLabel  matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        stress_psd_fatigue_Basquinver20byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        imat % Description
        iu
        mlab
        THM
        method
    end
    
    methods (Access = private)

        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end


        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end


        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end

        function unit_material(app)     

            app.ResultsTextArea.Value='';

            Items = char(app.StressUnitListBox.Items);
            im=app.StressUnitListBox.Value;
            [app.iu]=find_index(Items,im);

            n=app.iu;
            
            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);

            n_mat=app.imat;

            app.mlab='other'; 
             
            if(n_mat==1) % aluminum 6061-T6
                m=9.25; 
                app.mlab='aluminum 6061-T6';
            end
            if(n_mat==2) % butt-welded steel
                m=3.5;
                app.mlab='butt-welded steel';
            end    
            if(n_mat==3) % stainless steel
                m=6.54;
                app.mlab='stainless steel';
            end
             
            if(n_mat==4) % Petrucci: aluminum
                m=7.3; 
                app.mlab='Petrucci: aluminum 2219-T851';
            end
            if(n_mat==5) % Petrucci: steel
                m=3.324;
                app.mlab='Petrucci: steel';    
            end    
            if(n_mat==6) % Petrucci: spring steel
                m=11.760;
                app.mlab='Petrucci: spring steel';       
            end
             
             
            if(n_mat==1)  % aluminum 6061-T6
             
                Aksi=9.7724e+17;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=5.5757e+25;        
                end
                
            end
            if(n_mat==2)  % butt-welded steel
             
                  
                Aksi=1.255e+11;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=1.080e+14;      
                end    
                
            end
            if(n_mat==3)  % stainless steel
               
                Aksi=1.32E+18;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=4.0224e+23;      
                end        
                
            end
            if(n_mat==4)  % Petrucci: aluminum
               
                Aksi=5.18E+13;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=6.85E+19;      
                end        
                
            end
            if(n_mat==5)  % Petrucci: steel
               
                Aksi=3.16E+09;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=1.93E+12;      
                end        
                
            end
            if(n_mat==6)  % Petrucci: spring steel
               
                Aksi=1.95E+27;
                
                if(n==1)
                    A=((Aksi^(1/m))*1000)^m;
                end
                if(n==2)
                    A=Aksi;        
                end    
                if(n==3)
                    A=1.41E+37;      
                end        
                
            end


            if(n_mat<=6)
                ms=sprintf('%g',m);    
                As=sprintf('%8.4g',A);
                
                
                if(n==1)
                    ss=sprintf('psi^%g',m);
                end
                if(n==2)
                    ss=sprintf('ksi^%g',m);        
                end
                if(n==3)
                    ss=sprintf('MPa^%g',m);        
                end
                
            else
                ms='';    
                As='';    
            
                if(n==1)
                    ss=sprintf('psi^m');
                end
                if(n==2)
                    ss=sprintf('ksi^m');        
                end
                if(n==3)
                    ss=sprintf('MPa^m');        
                end
               
            end

            app.unitLabel.Text=ss;

            app.FatigueExponentMEditField.Value=ms;
            app.FatigueStrengthCoefficientAEditField.Value=As;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            unit_material(app);
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)

            unit_material(app);
        end

        % Value changed function: StressUnitListBox
        function StressUnitListBoxValueChanged(app, event)

            unit_material(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            

            scf=str2num(app.StressScaleFactorEditField.Value);
            
            [f,~,~,rms,df,app.THM,fi,ai]=fatigue_psd_check(app.THM,scf);
            
            %
            
            fig_num=1;
            
            n=app.iu;

            fmin=f(1);
            fmax=max(f);
            
            t_string='Stress PSD';
            xlab='Frequency (Hz)';
            
            if(n==1)
                ylab='Stress (psi^2/Hz)';
                t_string=sprintf('Stress PSD  scale factor=%g    %7.3g psi RMS',scf,rms);
            end
            if(n==2)
                ylab='Stress (ksi^2/Hz)';
                t_string=sprintf('Stress PSD  scale factor=%g    %7.3g ksi RMS',scf,rms);
            end
            if(n==3)
                ylab='Stress (MPa^2/Hz)';
                t_string=sprintf('Stress PSD  scale factor=%g    %7.3g MPa RMS',scf,rms);
            end 
            
            
            out1=sprintf('PSD unit:  %s ',ylab);
            disp(out1);
            
             
            out1=sprintf('\n Material: %s ',app.mlab);
            disp(out1);
            
            
            [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string,app.THM,fmin,fmax);
            
            %
            sigma_s=rms;
            %
           
            m=str2num(app.FatigueExponentMEditField.Value);
            A=str2num(app.FatigueStrengthCoefficientAEditField.Value);
            tau=str2num(app.DurationsecEditField.Value);

            if(isempty(tau))
                warndlg('Enter duration');
                return;
            end

            mss=app.FatigueExponentMEditField.Value;
            
            
            out1=sprintf(' Fatigue exponent m = %g ',m);
            disp(out1);
            out1=sprintf(' Fatigue strength coefficient = %g ',A);
            disp(out1);
            out1=sprintf(' Duration = %g sec \n',tau);
            disp(out1);
            out1=sprintf(' Stress concentration factor = %g \n',scf);
            disp(out1);
            
            
            clear length;
            n=length(fi);
            %
            m0=0;
            m1=0;
            m2=0;
            m4=0;
            M2m=0;
            Mkp2=0;
            m0p75=0;
            m1p5=0;
            %
            m_fatigue_exponent=0;
            %
            ae=2/m;
            be=ae+2;
            %
            for i=1:n
            %    
                m0=m0+ai(i)*df;
                m1=m1+ai(i)*fi(i)*df;
                m2=m2+ai(i)*fi(i)^2*df;
                m4=m4+ai(i)*fi(i)^4*df;
                M2m=M2m+ai(i)*fi(i)^ae*df;
                Mkp2=Mkp2+ai(i)*fi(i)^be*df;
            %    
                m0p75=m0p75+ai(i)*fi(i)^0.75*df;
                m1p5=m1p5+ai(i)*fi(i)^1.5*df;
            %
                m_fatigue_exponent=m_fatigue_exponent+ai(i)*fi(i)^m*df;
            %
            end
            %
            out1=sprintf('m0=%8.4g \nm1=%8.4g \nm2=%8.4g \nm4=%8.4g \n',m0,m1,m2,m4);
            disp(out1);
            out1=sprintf('M2m=%8.4g \nMkp2=%8.4g \nm0p75=%8.4g \nm1p5=%8.4g \n',M2m,Mkp2,m0p75,m1p5);
            disp(out1);
            %% out1=sprintf('m_fatigue_exponent=%8.4g \n',m_fatigue_exponent);
            %% disp(out1);
            %
            %
            alpha_0p75=m0p75/sqrt(m0*m1p5);
            alpha_1=m1/sqrt(m0*m2);
            alpha_2=m2/sqrt(m0*m4);
            %
            vo=sqrt(m2/m0);
            vp=sqrt(m4/m2);
            %
            alpha=vo/vp;
            e=sqrt(1-alpha^2);
            %
            delta=sqrt(1-alpha_1);
            %
            arg=0.5*m+1;
            gf=gamma(arg);
            %
            beta=sqrt(m2*M2m/(m0*Mkp2));
            betaem=beta^m;
            %
            lambda_oc=betaem/alpha;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            DNB=((vo*tau)/A)*gf*(sqrt(2)*sigma_s)^m;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            DOC=DNB*lambda_oc;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            a=0.926-0.033*m;
            b=1.587*m-2.323;
            ee=sqrt(1-alpha_2);
            lambda_wl=a+(1-a)*(1-ee)^b;
            %
            DWL=DNB*lambda_wl;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            lambda_ll=M2m^(m/2)/(vo*sigma_s^m);
            DLL=DNB*lambda_ll;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            h=1+alpha_1*alpha_2-(alpha_1+alpha_2);
            term1=1.112*h*exp(2.11*alpha_2);
            term2=(alpha_1-alpha_2);
            %
            b=(alpha_1-alpha_2)*(term1+term2)/(alpha_2-1)^2;
            lambda_bt=(b+(1-b)*alpha_2^(m-1));
            DBT=DNB*lambda_bt;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            DAL=alpha_0p75^2*DNB;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [DZB]=sf_Zhao_Baker(fi,ai,m,A,df,sigma_s,tau,vp,m0,m1,m2,m4,alpha_2);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            T=tau;
            
            [DDK]=sf_Dirlik(m,A,T,m0,m1,m2,m4);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            out1=sprintf('     Rate of Zero Crossings = %8.4g Hz',vo);
            out2=sprintf('              Rate of Peaks = %8.4g Hz',vp);
            out3=sprintf('  Irregularity Factor alpha = %8.4g ',alpha);
            out4=sprintf('   Spectral Width Parameter = %8.4g ',e);
            out42=sprintf('       Vanmarckes Parameter = %8.4g ',delta);
            %
            out5=sprintf('     Wirsching Light = %8.4g ',lambda_wl);
            out6=sprintf('          Ortiz Chen = %8.4g ',lambda_oc);
            out7=sprintf('      Lutes & Larsen = %8.4g ',lambda_ll);
            %
            ss=sprintf('%s\n%s',out1,out2);
            
            DNBr=DNB/tau;
            DDKr=DDK/tau; 
            DALr=DAL/tau;
            DOCr=DOC/tau;
            DZBr=DZB/tau;
            DLLr=DLL/tau;
            DWLr=DWL/tau; 
            DBTr=DBT/tau;
            
            %
            
            %
             out8=sprintf('         Narrowband DNB = %7.3g, \t %6.2e, \t %6.2e',DNB,DNBr,A*DNBr);
             out9=sprintf('             Dirlik DDK = %7.3g, \t %6.2e, \t %6.2e',DDK,DDKr,A*DDKr); 
            out10=sprintf('         Alpha 0.75 DAL = %7.3g, \t %6.2e, \t %6.2e',DAL,DALr,A*DALr);
            out11=sprintf('         Ortiz Chen DOC = %7.3g, \t %6.2e, \t %6.2e',DOC,DOCr,A*DOCr);
            out12=sprintf('         Zhao Baker DZB = %7.3g, \t %6.2e, \t %6.2e',DZB,DZBr,A*DZBr);
            out13=sprintf('       Lutes Larsen DLL = %7.3g, \t %6.2e, \t %6.2e',DLL,DLLr,A*DLLr);
            out14=sprintf('    Wirsching Light DWL = %7.3g, \t %6.2e, \t %6.2e',DWL,DWLr,A*DWLr); 
            out15=sprintf('   Benasciutti Tovo DBT = %7.3g, \t %6.2e, \t %6.2e',DBT,DBTr,A*DBTr);
            
             
            %
            out2001=sprintf('%s \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e \t %6.2e',...
                                                   FS,DNBr,DDKr,DALr,DOCr,DZBr,DLLr,DWLr,DBTr,vo,vp);
                                            
            data=[DNBr DDKr DALr DOCr DZBr DLLr DWLr DBTr vo vp];
            
            
            ms=sprintf('%s',mss);
            ms = strrep(ms, '.', 'p');
            output_name=sprintf('%s_%s_smf',FS,ms);
            assignin('base', output_name, data);                                   
            %
            disp(' ');
            disp(out1);
            disp(out2);
            disp(' ');
            disp(out3);
            disp(out4);
            disp(out42);
            disp(' ');
            disp(' Lambda Values   ');
            disp(' ');
            disp(out5);
            disp(out6);
            disp(out7);
            disp(' ');
            
            nn=app.iu;
            
                          disp('      Cumulative Damage             Damage Rate           A*rate  '); 
            
            if(nn==1)
                out999=sprintf('                                      (1/sec)         ((psi^%g)/sec) ',m);
            end
            if(nn==2)
                out999=sprintf('                                      (1/sec)         ((ksi^%g)/sec) ',m);
            end
            if(nn==3)
                out999=sprintf('                                      (1/sec)         ((MPa^%g)/sec) ',m);
            end  
            disp(out999);
            
            
            disp(' ');
            disp(out8);
            disp(out9);
            disp(out10);
            disp(out11);
            disp(out12);
            disp(out13);
            disp(out14);
            disp(out15);
            %
            disp(' ');
            disp(' Average of DAL,DOC,DLL,DBT,DZB,DDK ');
            %
            av=(DAL+DOC+DLL+DBT+DZB+DDK)/6;
            %
            out16=sprintf('\n          average=%8.4g \n',av);
            disp(out16);
  
            clipboard('copy', out2001)
            

            sq{1,1}='Narrowband DNB';
            sq{2,1}='Dirlik DDK';            
            sq{3,1}='Alpha 0.75 DAL';
            sq{4,1}='Ortiz Chen DOC';      
            sq{5,1}='Zhao Baker DZB';
            sq{6,1}='Lutes Larsen DLL';            
            sq{7,1}='Wirsching Light DWL';
            sq{8,1}='Benasciutti Tovo DBT';      
    
            sq{1,2}=sprintf('%6.2e',DNB);
            sq{2,2}=sprintf('%6.2e',DDK);            
            sq{3,2}=sprintf('%6.2e',DAL);
            sq{4,2}=sprintf('%6.2e',DOC);      
            sq{5,2}=sprintf('%6.2e',DZB);
            sq{6,2}=sprintf('%6.2e',DLL);            
            sq{7,2}=sprintf('%6.2e',DWL);
            sq{8,2}=sprintf('%6.2e',DBT);   

            sq{1,3}=sprintf('%6.2e',DNBr);
            sq{2,3}=sprintf('%6.2e',DDKr);            
            sq{3,3}=sprintf('%6.2e',DALr);
            sq{4,3}=sprintf('%6.2e',DOCr);      
            sq{5,3}=sprintf('%6.2e',DZBr);
            sq{6,3}=sprintf('%6.2e',DLLr);            
            sq{7,3}=sprintf('%6.2e',DWLr);
            sq{8,3}=sprintf('%6.2e',DBTr);  

            app.UITable.Data=sq;


            app.ResultsTextArea.Value=sprintf('%s\n\n%s\n%s',ss);

            app.TabGroup.SelectedTab=app.TabGroup.Children(3);


        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
     
            array_method(app);
        end

        % Value changing function: StressScaleFactorEditField
        function StressScaleFactorEditFieldValueChanging(app, event)
            
            app.ResultsTextArea.Value='';
            app.UITable.Data='';
        end

        % Value changing function: FatigueExponentMEditField
        function FatigueExponentMEditFieldValueChanging(app, event)
           app.ResultsTextArea.Value='';
           app.UITable.Data='';
        end

        % Value changing function: FatigueStrengthCoefficientAEditField
        function FatigueStrengthCoefficientAEditFieldValueChanging(app, event)
          
            app.ResultsTextArea.Value='';
            app.UITable.Data='';
        end

        % Callback function
        function DurationsecEditFieldValueChanging(app, event)
            
            app.ResultsTextArea.Value='';
            app.UITable.Data='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.stresspsdBasquinUIFigure = uifigure('Visible', 'off');
            app.stresspsdBasquinUIFigure.Name = 'stress psd Basquin';
            app.ReturnButton = uibutton(app.stresspsdBasquinUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.stress_psd_fatigue_Basquinver20byTomIrvineLabel = uilabel(app.stresspsdBasquinUIFigure);
            app.stress_psd_fatigue_Basquinver20byTomIrvineLabel.Text = {'stress_psd_fatigue_Basquin    ver 2.0  by Tom Irvine  '; ''; 'This script calculates the Palmgren-Miner cumulative fatigue damage from a stress PSD via a number of methods.'};
            app.TabGroup = uitabgroup(app.stresspsdBasquinUIFigure);
            app.StressPSDTab = uitab(app.TabGroup);
            app.StressPSDTab.Title = 'Stress PSD';
            app.TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel = uilabel(app.StressPSDTab);
            app.TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel.Text = 'The input PSD  array must have two columns:  freq (Hz) & stress (unit^2/Hz)';
            app.StressUnitListBoxLabel = uilabel(app.StressPSDTab);
            app.StressUnitListBoxLabel.Text = 'Stress Unit';
            app.StressUnitListBox = uilistbox(app.StressPSDTab);
            app.StressUnitListBox.Items = {'psi', 'ksi', 'MPa'};
            app.StressUnitListBox.ValueChangedFcn = createCallbackFcn(app, @StressUnitListBoxValueChanged, true);
            app.StressUnitListBox.Value = 'psi';
            app.listbox_method = uilistbox(app.StressPSDTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.StressPSDTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.StressPSDTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.StressPSDTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.StressPSDTab);
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.StressScaleFactorEditFieldLabel = uilabel(app.MaterialTab);
            app.StressScaleFactorEditFieldLabel.Text = 'Stress Scale Factor';
            app.StressScaleFactorEditField = uieditfield(app.MaterialTab, 'text');
            app.StressScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @StressScaleFactorEditFieldValueChanging, true);
            app.StressScaleFactorEditField.Value = '1';
            app.MaterialListBoxLabel = uilabel(app.MaterialTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialTab);
            app.MaterialListBox.Items = {'Aluminum 6061-T6', 'Butt-welded Steel Joint', 'Stainless Steel, PH13-8Mo(H1000)', 'Petrucci: Alumimum 2219-T851', 'Petrucci: Steel', 'Petrucci: Spring Steel', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum 6061-T6';
            app.FatigueExponentMEditFieldLabel = uilabel(app.MaterialTab);
            app.FatigueExponentMEditFieldLabel.Text = 'Fatigue Exponent M';
            app.FatigueExponentMEditField = uieditfield(app.MaterialTab, 'text');
            app.FatigueExponentMEditField.ValueChangingFcn = createCallbackFcn(app, @FatigueExponentMEditFieldValueChanging, true);
            app.CalculateButton = uibutton(app.MaterialTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FatigueStrengthCoefficientAEditFieldLabel = uilabel(app.MaterialTab);
            app.FatigueStrengthCoefficientAEditFieldLabel.Text = 'Fatigue Strength Coefficient A';
            app.FatigueStrengthCoefficientAEditField = uieditfield(app.MaterialTab, 'text');
            app.FatigueStrengthCoefficientAEditField.ValueChangingFcn = createCallbackFcn(app, @FatigueStrengthCoefficientAEditFieldValueChanging, true);
            app.unitLabel = uilabel(app.MaterialTab);
            app.unitLabel.Text = 'unit';
            app.DurationsecEditFieldLabel = uilabel(app.MaterialTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.MaterialTab, 'text');
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTextAreaLabel = uilabel(app.ResultsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ResultsTab);
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'Method'; 'Cumulative Damage'; 'Damage Rate (1/sec)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            % Show the figure after all components are created
            app.stresspsdBasquinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stress_psd_fatigue_Basquin

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.stresspsdBasquinUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.stresspsdBasquinUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.stresspsdBasquinUIFigure)
        end
    end
end