classdef stress_psd_fatigue_nasgro_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        stresspsdfatiguenasgroUIFigure  matlab.ui.Figure
        Label                           matlab.ui.control.Label
        stress_psd_fatigue_nasgro_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputStressPSDTab               matlab.ui.container.Tab
        IfnotalreadycoveredinstressPSDorbycoefficientsLabel  matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        StressScaleFactorEditField      matlab.ui.control.EditField
        StressScaleFactorEditFieldLabel  matlab.ui.control.Label
        StressUnitListBox               matlab.ui.control.ListBox
        StressUnitListBoxLabel          matlab.ui.control.Label
        TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel  matlab.ui.control.Label
        CalculateTab                    matlab.ui.container.Tab
        ViewEquationButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RSminSmaxR1forfullyreversedstressR1meantensilestressLabel  matlab.ui.control.Label
        StressRatioREditField           matlab.ui.control.EditField
        StressRatioREditFieldLabel      matlab.ui.control.Label
        FromSNcurvewithstressinksiSeealsoMILHDBK5JLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        EnterDurationsecEditField       matlab.ui.control.EditField
        EnterDurationsecEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM % Description
        scf
        A
        B
        C
        P
        R
        tau
        iu
        ncols
        mlab
    end
    
    methods (Access = private)

        function ClosePlots(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
        end    

        function material(app)

            Items = strtrim(app.MaterialListBox.Items);
            im = strtrim(app.MaterialListBox.Value);
            n = find(strcmp(Items, im));

            % 1. Aluminum 6061-T6
            % 2. Aluminum 7050-T7451
            % 3. Aluminum 7075-T6
            % 4. Inconel 718
            % 5. Stainless Steel Custom 450 (H1050)
            % 6. Stainless Steel 15-5PH, 1025 
            
            [app.mlab,As,Bs,Cs,Ps]=SN_coefficients(n);

            data={As;Bs;Cs;Ps};
            
            app.UITable.Data=data;

            app.ResultsTextArea.Value='';
        end
        
        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            app.listbox_method.Value=app.listbox_method.Items{1};
        end
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            app.UITable.RowName={'A'; 'B'; 'C';'P'};
            app.UITable.Data={' '; ' '; ' '; ' '};
            app.UITable.ColumnWidth={89};
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style 
            material(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               try
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);

            fig_num=1;
            
            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            app.tau=str2num(app.EnterDurationsecEditField.Value);

            app.scf=str2num(app.StressScaleFactorEditField.Value);

            app.R=str2num(app.StressRatioREditField.Value);

            Items = strtrim(app.StressUnitListBox.Items);
            im = strtrim(app.StressUnitListBox.Value);
            app.iu = find(strcmp(Items, im));

            QZ=app.UITable.Data;
            
            QZ=strtrim(char(QZ));

            app.A=str2num(QZ(1,:));
            app.B=str2num(QZ(2,:));
            app.C=str2num(QZ(3,:));
            app.P=str2num(QZ(4,:));            

            if(app.B<0)
                warndlg(' B coefficient should be > 0 '); 
                return;
            end
            
            sz=size(app.THM);
            
            app.ncols=sz(2)-1;
            
            if(app.iu==1) % psi to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/1000^2;
            end
            if(app.iu==3) % Pa to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/( 6891^2  );
            end
            if(app.iu==4) % MPa to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/(( 6891e-06  )^2);
            end
            
            %%%%%%%%%%%%%%

            [~,~,~,rms,df,app.THM,fi,ai]=fatigue_psd_check(app.THM,app.scf);
            
            fig_num=1;
            
            xlab='Frequency (Hz)';
            
            sss=sprintf(' %8.4g ksi RMS ',rms);  
            t_string=sprintf('Stress PSD   %s  stress scale factor=%g',sss,app.scf);
             
            ylab='Stress (ksi^2/Hz)';
            
            s_psd_unit=sprintf('PSD unit:  %s ',ylab);
             
            md=5; 
            
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
            
            [fig_num,~]=...
                plot_loglog_function_md_h2(fig_num,xlab,ylab,t_string,app.THM,fmin,fmax,md);
            
            sigma_s=rms;
            
            clear length;
            n=length(fi);
            %
                [m0,m1,m2,m4,M2m,~,m0p75,m1p5,vo,EP,alpha2,e,D1,D2,D3,Q,Rd]=...
                                                 spectral_moments_nasgro(n,app.B,ai,fi,df);
            %

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Note that range = (peak-valley)
            %
            %  maxS is the assumed upper limit of the the histogram.
            %
            %  The value 6*grms is used as conservative estimate of the upper
            %  range maxS for all cycles.  
            %
            %  The histogram will have 400 bins.  This number is chosen
            %  via engineering judgement.
            %
            %    ds is the bin range width       
            %     n is the number of bins
            %     N is the cycle count per bin
            %     S is the range level for each bin
            %
            rms=sqrt(m0);
            
            maxS=8*rms;  
            %
            ds=maxS/800;
            %
            n=round(maxS/ds);
            %
            EP_tau=EP*app.tau;
            
            %%%%
            %
            %   Dirlik
            %
            
            out1=sprintf(' n=%d  m0=%8.4g  ds=%8.4g  Rd=%8.4g   ',n,m0,ds,Rd);
            out2=sprintf(' D1=%8.3g  D2=%8.3g  D3=%8.3g  Q=%8.3g   Rd=%8.3g ',D1,D2,D3,Q,Rd);
            out3=sprintf(' D1/Q=%8.3g  D2/R^2=%8.3g  D3=%8.3g  ',D1/Q,D2/app.R^2,D3);
            
            [Dirlik_N,range,dz]=Dirlik_nasgro_probability(n,m0,Q,D1,D2,D3,ds,Rd);
            
            out4=sprintf('*** max DN=%8.4g   min DN=%8.4g ',max(Dirlik_N),min(Dirlik_N));
            out5=sprintf('*** max Range=%8.4g   min Range=%8.4g ',max(range),min(range));

            ss=sprintf('%s\n%s\n%s\n%s\n%s\n',out1,out2,out3,out4,out5);

            DN=Dirlik_N/sum(Dirlik_N);
            
            aaaa=(range/2); 
            bbbb=DN*EP_tau/ds;
            
            size(aaaa);
            size(bbbb);

            assignin('base', 'Dirlik_N_with_scf',[ (range/2)  DN*EP_tau/ds]);

            % assignin('base', 'Dirlik_N_without_scf',[ (range/2)/a[[.scf  DN*EP_tau/ds]);
      
            out6=sprintf('n=%d A=%8.4g B=%8.4g C=%8.4g P=%8.4g R=%8.4g ',n,app.A,app.B,app.C,app.P,app.R);
            out7=sprintf(' EP_tau=%8.4g  dz=%8.4g ',EP_tau,dz);
  
            [Dirlik_damage]=Dirlik_nasgro(n,app.A,app.B,app.C,app.P,app.R,EP_tau,Dirlik_N,range,dz);
            
            out8=sprintf(' Dirlik_damage=%8.4g ',Dirlik_damage);            
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Narrowband
            %
            
            D1=0;
            D2=0;
            D3=1;
            
            [Narrowband_N,range,dz]=Dirlik_nasgro_probability(n,m0,Q,D1,D2,D3,ds,Rd);
            
            vo_tau=vo*app.tau;  % important for narrowband
            
            [Narrowband_damage]=Dirlik_nasgro(n,app.A,app.B,app.C,app.P,app.R,vo_tau,Narrowband_N,range,dz);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            % mlab=getappdata(0,'mlab');
             
            out9=sprintf('Material: %s ',app.mlab);
            out10=sprintf('      A=%g  B=%g  C=%g  P=%g ',app.A,app.B,app.C,app.P);

            ss=sprintf('%s%s',out9,out10);

            out11=sprintf('\n m0=%8.4g   m1=%8.4g   m2=%8.4g   m4=%8.4g ',m0,m1,m2,m4);
            out12=sprintf('\n ds=%8.4g dz=%8.4g',ds,dz);
            out13=sprintf('\n Duration=%8.4g sec \n',app.tau);
            out14=sprintf('    v0+=%8.4g Hz, rate of zero upcrossings  ',vo);
            out15=sprintf('     EP=%8.4g Hz, expected peak rate ',EP);

            ss=sprintf('%s\n%s\n%s\n%s\n%s\n',ss,out11,out13,out14,out15);
  
            out16=sprintf(' alpha2=%8.4g ',alpha2);
            out17=sprintf('      e=%8.4g spectral width',e);

            a=vo*app.tau;
            c=sqrt(2*log(a));
            ps=c + 0.5772/c;
             
            out18=sprintf('\n Stress Ratio R = %g',app.R);
            out19=sprintf(' Stress Scale Factor = %g',app.scf); 
            out20=sprintf(' Overall Stress Level with Stress Scale Factor = %8.4g ksi rms ',sqrt(m0));

            ss=sprintf('%s\n%s\n%s\n%s\n%s\n%s\n',ss,out16,out17,out18,out19,out20);

            out21=sprintf(' The maximum expected peak response with scale factor is: %8.4g ksi (%4.3g sigma) ',ps*rms,ps);

            ss=sprintf('%s\n%s\n\n     Accumulated Damage ',ss,out21);
            
            DNB=Narrowband_damage;
            
            m=app.B;
            
            lambda_ll=M2m^(m/2)/(vo*sigma_s^m);
            LL_damage=DNB*lambda_ll;
            
            alpha_0p75=m0p75/sqrt(m0*m1p5);
            
            DAL=alpha_0p75^2*DNB;
           
            out22=sprintf('\n        Dirlik = %8.4g ',Dirlik_damage);
            out23=sprintf(' Single-Moment = %8.4g ',LL_damage);
            out24=sprintf('    alpha_0p75 = %8.4g ',DAL);

            ss=sprintf('%s\n%s\n%s\n%s\n\n   Damage Rate ',ss,out22,out23,out24);
            
            out25=sprintf('\n        Dirlik = %8.4g per sec',Dirlik_damage/app.tau);
            out26=sprintf(' Single-Moment = %8.4g per sec',LL_damage/app.tau);
            out27=sprintf('    alpha_0p75 = %8.4g per sec',DAL/app.tau);
            
            ss=sprintf('%s\n%s\n%s\n%s\n\n   Time to Failure for Cumulative Damage Index=1 ',ss,out25,out26,out27);

            tfail=app.tau/Dirlik_damage;
            d=floor(tfail/86400);
            trem=tfail-d*86400;
            h=floor(trem/3600);
            trem=trem-h*3600;
            m=floor(trem/60);
            trem=trem-m*60;
            s=floor(trem);
            
            out28=sprintf('\n        Dirlik = %8.4g sec ',tfail ); 
            out29=sprintf('               = %g days %g hr %g min %g sec \n',d,h,m,s);
            
            ss=sprintf('%s\n%s\n%s\n   Time to Failure for Cumulative Damage Index=0.1 ',ss,out28,out29);

            tfail=app.tau/Dirlik_damage/10;
            d=floor(tfail/86400);
            trem=tfail-d*86400;
            h=floor(trem/3600);
            trem=trem-h*3600;
            m=floor(trem/60);
            trem=trem-m*60;
            s=floor(trem);
            
            out30=sprintf('\n        Dirlik = %8.4g sec ',tfail );
            out31=sprintf('               = %g days %g hr %g min %g sec \n',d,h,m,s);                    

            ss=sprintf('%s\n%s\n%s  ',ss,out30,out31);

            app.ResultsTextArea.Value=ss;

            app.TabGroup.SelectedTab=app.ResultsTab;

        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            
            material(app);
        end

        % Value changed function: StressUnitListBox
        function StressUnitListBoxValueChanged(app, event)
            
            app.ResultsTextArea.Value='';
        end

        % Value changed function: StressScaleFactorEditField
        function StressScaleFactorEditFieldValueChanged(app, event)

            app.ResultsTextArea.Value='';
        end

        % Value changing function: EnterDurationsecEditField
        function EnterDurationsecEditFieldValueChanging(app, event)
          
            app.ResultsTextArea.Value='';
        end

        % Value changing function: StressRatioREditField
        function StressRatioREditFieldValueChanging(app, event)
           
            app.ResultsTextArea.Value='';
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            app.ResultsTextArea.Value='';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
          
            app.ResultsTextArea.Value='';
        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            AA = imread('nasgro_coefficients.jpg');
            figure(555);
            imshow(AA,'border','tight','InitialMagnification',100);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.stresspsdfatiguenasgroUIFigure = uifigure('Visible', 'off');
            app.stresspsdfatiguenasgroUIFigure.Name = 'stress psd fatigue nasgro';
            app.ReturnButton = uibutton(app.stresspsdfatiguenasgroUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.stresspsdfatiguenasgroUIFigure);
            app.InputStressPSDTab = uitab(app.TabGroup);
            app.InputStressPSDTab.Title = 'Input Stress PSD';
            app.TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel = uilabel(app.InputStressPSDTab);
            app.TheinputPSDarraymusthavetwocolumnsfreqHzstressunit2HzLabel.Text = 'The input PSD  array must have two columns:  freq (Hz) & stress (unit^2/Hz)';
            app.StressUnitListBoxLabel = uilabel(app.InputStressPSDTab);
            app.StressUnitListBoxLabel.Text = 'Stress Unit';
            app.StressUnitListBox = uilistbox(app.InputStressPSDTab);
            app.StressUnitListBox.Items = {'psi', 'ksi', 'Pa', 'MPa'};
            app.StressUnitListBox.ValueChangedFcn = createCallbackFcn(app, @StressUnitListBoxValueChanged, true);
            app.StressUnitListBox.Value = 'psi';
            app.StressScaleFactorEditFieldLabel = uilabel(app.InputStressPSDTab);
            app.StressScaleFactorEditFieldLabel.Text = 'Stress Scale Factor';
            app.StressScaleFactorEditField = uieditfield(app.InputStressPSDTab, 'text');
            app.StressScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @StressScaleFactorEditFieldValueChanged, true);
            app.StressScaleFactorEditField.Value = '1';
            app.listbox_method = uilistbox(app.InputStressPSDTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputStressPSDTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputStressPSDTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputStressPSDTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputStressPSDTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.IfnotalreadycoveredinstressPSDorbycoefficientsLabel = uilabel(app.InputStressPSDTab);
            app.IfnotalreadycoveredinstressPSDorbycoefficientsLabel.Text = '(If not already covered in stress PSD or by coefficients)';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EnterDurationsecEditFieldLabel = uilabel(app.CalculateTab);
            app.EnterDurationsecEditFieldLabel.Text = 'Enter Duration (sec)';
            app.EnterDurationsecEditField = uieditfield(app.CalculateTab, 'text');
            app.EnterDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @EnterDurationsecEditFieldValueChanging, true);
            app.MaterialListBoxLabel = uilabel(app.CalculateTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.CalculateTab);
            app.MaterialListBox.Items = {'Aluminum 6061-T6, Kt=1.0', 'Aluminum 7050-T7451, Kt=1.0', 'Aluminum 7075-T6, Kt=1.0', 'Inconel 718, Kt=1.0', 'Stainless Steel Custom 450 (H1050), Kt=3.0', 'Stainless Steel 15-5PH 1025, Kt=3.0', 'Other           '};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum 6061-T6, Kt=1.0';
            app.FromSNcurvewithstressinksiSeealsoMILHDBK5JLabel = uilabel(app.CalculateTab);
            app.FromSNcurvewithstressinksiSeealsoMILHDBK5JLabel.Text = {'Nasgro Coefficients'; ''; 'From S/N curve with stress in ksi  '; ''; '(See also MIL-HDBK-5J)'};
            app.StressRatioREditFieldLabel = uilabel(app.CalculateTab);
            app.StressRatioREditFieldLabel.Text = 'Stress Ratio R';
            app.StressRatioREditField = uieditfield(app.CalculateTab, 'text');
            app.StressRatioREditField.ValueChangingFcn = createCallbackFcn(app, @StressRatioREditFieldValueChanging, true);
            app.StressRatioREditField.Value = '-1';
            app.RSminSmaxR1forfullyreversedstressR1meantensilestressLabel = uilabel(app.CalculateTab);
            app.RSminSmaxR1forfullyreversedstressR1meantensilestressLabel.Text = {'R = Smin/Smax'; ''; 'R = -1 for fully reversed stress'; ''; 'R > -1  mean tensile stress'; ''};
            app.UITable = uitable(app.CalculateTab);
            app.UITable.ColumnName = {'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ViewEquationButton = uibutton(app.CalculateTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTextAreaLabel = uilabel(app.ResultsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ResultsTab);
            app.stress_psd_fatigue_nasgro_aver10byTomIrvineLabel = uilabel(app.stresspsdfatiguenasgroUIFigure);
            app.stress_psd_fatigue_nasgro_aver10byTomIrvineLabel.Text = 'stress_psd_fatigue_nasgro_a    ver 1.0    by Tom Irvine';
            app.Label = uilabel(app.stresspsdfatiguenasgroUIFigure);
            app.Label.Text = 'This script calculates the Palmgren-Miner fatigue damage from a stress response PSD via the Dirlik method.';
            % Show the figure after all components are created
            app.stresspsdfatiguenasgroUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stress_psd_fatigue_nasgro_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.stresspsdfatiguenasgroUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.stresspsdfatiguenasgroUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.stresspsdfatiguenasgroUIFigure)
        end
    end
end