classdef structural_dynamics_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIStructuralDynamicsMainUIFigure  matlab.ui.Figure
        TabsLocationButtonGroup        matlab.ui.container.ButtonGroup
        RightButton                    matlab.ui.control.ToggleButton
        TopButton                      matlab.ui.control.ToggleButton
        structural_dynamics_mainver12byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SpringMassSystemsTab           matlab.ui.container.Tab
        AnalyzeButton                  matlab.ui.control.Button
        SelectSystemListBox            matlab.ui.control.ListBox
        SelectSystemListBoxLabel       matlab.ui.control.Label
        SelectNumberofDOFListBox       matlab.ui.control.ListBox
        SelectNumberofDOFListBoxLabel  matlab.ui.control.Label
        StructuresSystemsTab           matlab.ui.container.Tab
        SelectAnalysisStructuresListBox  matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_5   matlab.ui.control.Label
        AnalyzeButtonStructures        matlab.ui.control.Button
        EigenvalueProblemsTab          matlab.ui.container.Tab
        SelectAnalysisEigenListBox     matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_4   matlab.ui.control.Label
        AnalyzeButtonEigen             matlab.ui.control.Button
        NastranToolboxTab              matlab.ui.container.Tab
        AnalyzeButtonNastran           matlab.ui.control.Button
        SelectAnalysisNastranListBox   matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_3   matlab.ui.control.Label
        TransferFunctionsTab           matlab.ui.container.Tab
        SelectAnalysisTransListBox     matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_2   matlab.ui.control.Label
        AnalyzeButtonTrans             matlab.ui.control.Button
        MiscellaneousTab               matlab.ui.container.Tab
        AnalyzeButtonMisc              matlab.ui.control.Button
        SelectAnalysisMiscListBox      matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel     matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        ndof % Description
    end
    
    methods (Access = private)
        
        function ChangeSpringMass(app)
            n= strtrim(app.SelectNumberofDOFListBox.Value);
            
            if(contains(n,'Single'))
                app.ndof=1;
                string_w{1}=sprintf('Natural Frequency');
                string_w{2}=sprintf('Steady-State Sine Force or Acceleration Input');
                string_w{3}=sprintf('Classical Shock Base Input');
                string_w{4}=sprintf('Base Input Time Domain');
                string_w{5}=sprintf('Classical Shock Applied Force');
                string_w{6}=sprintf('Applied Force Time Domain');
                string_w{7}=sprintf('PSD Base Input');
                string_w{8}=sprintf('Peak Sigma for Random Base Input');
                string_w{9}=sprintf('Transmissibility for Base Excitation');
                string_w{10}=sprintf('Transfer Function for Applied Force');   
                string_w{11}=sprintf('Free Vibration Response to Initial Conditions');
                string_w{12}=sprintf('Spring Surge');                
            end
            if(contains(n,'Two'))
                app.ndof=2;
                string_w{1}=sprintf('System Natural Frequencies');
                string_w{2}=sprintf('System Base Excitation');
                string_w{3}=sprintf('System Applied Force');   
                string_w{4}=sprintf('System with Dashpots');
                string_w{5}=sprintf('Three Parameter Isolation System');  
                string_w{6}=sprintf('Automobile');                    
            end
            if(contains(n,'Three'))
                app.ndof=3;
                string_w{1}=sprintf('System Natural Frequencies');
                string_w{2}=sprintf('System Base Excitation');
                string_w{3}=sprintf('ESA Simple Spacecraft Model');
                string_w{4}=sprintf('ESA Simple Launcher/Spacecraft Model');
                string_w{5}=sprintf('Semidefinite System, Applied Force');                
            end
            if(contains(n,'Multiple'))
                app.ndof=4;
                string_w{1}=sprintf('Six-DOF Mass with Four Isolators');
                string_w{2}=sprintf('Six-DOF Mass with Arbitrary Number of Isolators');    
                string_w{3}=sprintf('MDOF System, Applied Arbitary Force');
                string_w{4}=sprintf('MDOF System, Enforced Acceleration');
                string_w{5}=sprintf('MDOF System, Acceleration & Force');
                string_w{6}=sprintf('MDOF System, Enforced Dispacement');
                string_w{7}=sprintf('FEA Preprocessor');
            end            
            
            app.SelectSystemListBox.Items=string_w;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeSpringMass(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: AnalyzeButtonMisc
        function AnalyzeButtonMiscPushed(app, event)
        
           m=app.SelectAnalysisMiscListBox.Value;
        
           if(contains(m,'Damping'))
               handles.s=damping_coefficient_matrix;
           end  
           if(contains(m,'Stress'))
               handles.s=vibrationdata_stress_velocity;
           end  
           if(contains(m,'Dunkerley'))
               handles.s=Dunkerley;
           end   
           if(contains(m,'Inertia'))
               handles.s=area_moment_inertia;
           end     

           set(handles.s,'Visible','on'); 
        end

        % Button pushed function: AnalyzeButtonTrans
        function AnalyzeButtonTransPushed(app, event)
            m=app.SelectAnalysisTransListBox.Value;    
        
            if(contains(m,'from Modes'))
                handles.s=transfer_functions_from_modes;
            end  
            if(contains(m,'Response from'))
                handles.s=vibrationdata_blast;
            end
            set(handles.s,'Visible','on');

        end

        % Button pushed function: AnalyzeButtonNastran
        function AnalyzeButtonNastranPushed(app, event)
            m=app.SelectAnalysisNastranListBox.Value;
                        
            if(contains(m,'Eigenvalues'))
                handles.s=nastran_normal_modes;
            end
            if(contains(m,'Spectrum'))
                handles.s=nastran_normal_modes_response_spectrum;
            end
            if(contains(m,'Transient'))
                handles.s=nastran_modal_transient;
            end
            if(contains(m,'Frequency Response'))
                handles.s=nastran_base_input_frf;
            end
            if(contains(m,'Power Spectral'))
                handles.s=nastran_base_input_psd;
            end 
            set(handles.s,'Visible','on');

        end

        % Button pushed function: AnalyzeButtonEigen
        function AnalyzeButtonEigenPushed(app, event)
           m=strtrim(app.SelectAnalysisEigenListBox.Value);
        
           if(strcmp(m,'Generalized Eigenvalue Problem'))
                vibrationdata_generalized_eigenvalue_App;
           end  
           if(strcmp(m,'Standard Eigenvalue Problem'))
                handles.s=vibrationdata_standard_eigenvalue;
           end  
           if(strcmp(m,'Convert Generalized Eigenvalue Problem to Standard Form'))
                handles.s=vibrationdata_convert_general_standard;
           end  
           if(strcmp(m,'Convert Eigenvalue Problem to Tridiagonal Form'))
                handles.s=vibrationdata_tridiagonal;
           end    
           if(strcmp(m,'Modified Gram-Schmidt Orthogonalization'))
                handles.s=modified_Gram_Schmidt_orthogonalization;
           end      
           try
                set(handles.s,'Visible','on')
           catch
           end
        end

        % Button pushed function: AnalyzeButtonStructures
        function AnalyzeButtonStructuresPushed(app, event)
            m=strtrim(app.SelectAnalysisStructuresListBox.Value);
            
            if(strcmp(m,'Rod or Beam Longitudinal'))
               handles.s=beam_longitudinal;
            end
            if(strcmp(m,'Rod or Shaft Torsional'))
               handles.s=shaft_torsional;
            end
            if(strcmp(m,'Shaft & Disk System Torsional Holzer Method'))
               handles.s=Holzer_torsional;
            end 
            if(strcmp(m,'Beam Bending'))
               handles.s=beam_bending_menu;
            end 
            if(strcmp(m,'Plates Rectangular & Circular & Annular Bending'))
               handles.s=structural_dynamics_plates;
            end 
            if(strcmp(m,'Plates In-plane'))
               handles.s=vibrationdata_inplane_rectangular_plate_fea;
            end 
            if(strcmp(m,'Rings & Cylinders'))
               handles.s=rings_cylinders;
            end  
            if(strcmp(m,'Tall Building Natural Frequency'))
               handles.s=tall_building;
            end  
            set(handles.s,'Visible','on');

        end

        % Value changed function: SelectNumberofDOFListBox
        function SelectNumberofDOFListBoxValueChanged(app, event)
            ChangeSpringMass(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
            n=strtrim(app.SelectSystemListBox.Value);
            n=strrep(n,',','');
        
            if(app.ndof==1)
                if(strcmp(n,'Natural Frequency')) %1
                    handles.s=sdof_fn;
                end
                if(strcmp(n,'Steady-State Sine Force or Acceleration Input')) %2
                    handles.s=steady;
                end
                if(strcmp(n,'Classical Shock Base Input')) %3
                    classical_pulse_base_input_a;
                end
                if(strcmp(n,'Base Input Time Domain')) %4
                    handles.s=vibrationdata_sdof_base;
                end
                if(strcmp(n,'Classical Shock Applied Force')) %5
                    handles.s=classical_pulse_applied_force;
                end
                if(strcmp(n,'Applied Force Time Domain')) %6
                    handles.s=vibrationdata_sdof_Force;
                end
                if(strcmp(n,'PSD Base Input')) %7
                    handles.s=vibrationdata_psd_sdof_base;
                end
                if(strcmp(n,'Peak Sigma for Random Base Input')) %8
                    handles.s=peak_sigma_random;       
                end
                if(strcmp(n,'Transmissibility for Base Excitation')) %9
                    handles.s=vibrationdata_sdof_transmissibility;
                end
                if(strcmp(n,'Transfer Function for Applied Force')) %1o
                    handles.s=vibrationdata_sdof_transfer;
                end                
                if(strcmp(n,'Free Vibration Response to Initial Conditions')) %11
                    handles.s=sdof_free_vibration;
                end
                if(strcmp(n,'Spring Surge')) %12
                    handles.s=vibrationdata_spring_surge;
                end
                                
            end
            if(app.ndof==2)
                if(strcmp(n,'System Natural Frequencies'))
                    handles.s=two_dof_system;   
                end   
                if(strcmp(n,'System Base Excitation'))
                    handles.s=two_dof_base;   
                end    
                if(strcmp(n,'System Applied Force'))
                    handles.s=vibrationdata_two_dof_force;   
                end 
                if(strcmp(n,'System with Dashpots'))
                    handles.s=vibrationdata_two_dof_dashpots_force;   
                end   
                if(strcmp(n,'Three Parameter Isolation System'))
                    handles.s=three_parameter_isolation_system;   
                end    
                if(strcmp(n,'Automobile'))
                    handles.s=automobile_road;   
                end                 
            end 
            if(app.ndof==3)
                if(strcmp(n,'System Natural Frequencies'))
                    handles.s=three_dof_system;                   
                end 
                if(strcmp(n,'System Base Excitation'))
                    handles.s=three_dof_base_a;                   
                end
                if(strcmp(n,'ESA Simple Spacecraft Model'))
                    handles.s=ESA_simple_spacecraft_model;                   
                end
                if(strcmp(n,'ESA Simple Launcher/Spacecraft Model'))
                    handles.s=ESA_simple_launcher_spacecraft_model;                   
                end
                if(strcmp(n,'Semidefinite System Applied Force'))
                    handles.s=semi_definite_three_dof_applied_force;                   
                end               
            end
            if(app.ndof==4)
                if(strcmp(n,'Six-DOF Mass with Four Isolators'))
                    handles.s=six_dof_four_isolators;                   
                end 
                if(strcmp(n,'Six-DOF Mass with Arbitrary Number of Isolators'))
                    handles.s=vibrationdata_arbitrary_isolators;                   
                end  
                if(strcmp(n,'MDOF System Applied Arbitary Force'))
                    handles.s=mdof_modal_arbitrary_force_main;                   
                end
                if(strcmp(n,'MDOF System Enforced Acceleration'))
                    handles.s=nm_mdof_enforced_acceleration;                   
                end
                if(strcmp(n,'MDOF System Acceleration & Force'))
                    handles.s=nm_mdof_acceleration_force;                   
                end  
                if(strcmp(n,'MDOF System Enforced Dispacement'))
                    handles.s=nm_mdof_enforced_displacement;                   
                end  
                if(strcmp(n,'FEA Preprocessor'))
                    handles.s=vibrationdata_fea_preprocessor;                   
                end                  
            end     
            
            try
                set(handles.s,'Visible','on');
            catch        
            end
            
        end

        % Selection changed function: TabsLocationButtonGroup
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIStructuralDynamicsMainUIFigure = uifigure('Visible', 'off');
            app.UIStructuralDynamicsMainUIFigure.Name = 'UI Structural Dynamics Main';
            app.ReturnButton = uibutton(app.UIStructuralDynamicsMainUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.UIStructuralDynamicsMainUIFigure);
            app.SpringMassSystemsTab = uitab(app.TabGroup);
            app.SpringMassSystemsTab.Title = 'Spring-Mass Systems';
            app.SelectNumberofDOFListBoxLabel = uilabel(app.SpringMassSystemsTab);
            app.SelectNumberofDOFListBoxLabel.Text = 'Select Number of DOF';
            app.SelectNumberofDOFListBox = uilistbox(app.SpringMassSystemsTab);
            app.SelectNumberofDOFListBox.Items = {'Single', 'Two', 'Three', 'Multiple'};
            app.SelectNumberofDOFListBox.ValueChangedFcn = createCallbackFcn(app, @SelectNumberofDOFListBoxValueChanged, true);
            app.SelectNumberofDOFListBox.Value = 'Single';
            app.SelectSystemListBoxLabel = uilabel(app.SpringMassSystemsTab);
            app.SelectSystemListBoxLabel.Text = 'Select System';
            app.SelectSystemListBox = uilistbox(app.SpringMassSystemsTab);
            app.AnalyzeButton = uibutton(app.SpringMassSystemsTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StructuresSystemsTab = uitab(app.TabGroup);
            app.StructuresSystemsTab.Title = 'Structures & Systems';
            app.AnalyzeButtonStructures = uibutton(app.StructuresSystemsTab, 'push');
            app.AnalyzeButtonStructures.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructuresPushed, true);
            app.AnalyzeButtonStructures.Text = 'Analyze';
            app.SelectAnalysisListBoxLabel_5 = uilabel(app.StructuresSystemsTab);
            app.SelectAnalysisListBoxLabel_5.Text = 'Select Analysis';
            app.SelectAnalysisStructuresListBox = uilistbox(app.StructuresSystemsTab);
            app.SelectAnalysisStructuresListBox.Items = {'Rod or Beam Longitudinal', 'Rod or Shaft Torsional', 'Shaft & Disk System Torsional Holzer Method', 'Beam Bending', 'Plates Rectangular & Circular & Annular Bending', 'Plates In-plane', 'Rings & Cylinders', 'Tall Building Natural Frequency', ''};
            app.SelectAnalysisStructuresListBox.Value = 'Rod or Beam Longitudinal';
            app.EigenvalueProblemsTab = uitab(app.TabGroup);
            app.EigenvalueProblemsTab.Title = 'Eigenvalue Problems';
            app.AnalyzeButtonEigen = uibutton(app.EigenvalueProblemsTab, 'push');
            app.AnalyzeButtonEigen.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonEigenPushed, true);
            app.AnalyzeButtonEigen.Text = 'Analyze';
            app.SelectAnalysisListBoxLabel_4 = uilabel(app.EigenvalueProblemsTab);
            app.SelectAnalysisListBoxLabel_4.Text = 'Select Analysis';
            app.SelectAnalysisEigenListBox = uilistbox(app.EigenvalueProblemsTab);
            app.SelectAnalysisEigenListBox.Items = {'Generalized Eigenvalue Problem ', 'Standard Eigenvalue Problem', 'Convert Generalized Eigenvalue Problem to Standard Form', 'Convert Eigenvalue Problem to Tridiagonal Form', 'Modified Gram-Schmidt Orthogonalization', ''};
            app.SelectAnalysisEigenListBox.Value = 'Generalized Eigenvalue Problem ';
            app.NastranToolboxTab = uitab(app.TabGroup);
            app.NastranToolboxTab.Title = 'Nastran Toolbox';
            app.SelectAnalysisListBoxLabel_3 = uilabel(app.NastranToolboxTab);
            app.SelectAnalysisListBoxLabel_3.Text = 'Select Analysis';
            app.SelectAnalysisNastranListBox = uilistbox(app.NastranToolboxTab);
            app.SelectAnalysisNastranListBox.Items = {'Normal Modes Eigenvalues & Eigenvectors', 'Normal Modes Response Spectrum Analysis', 'Modal Transient Time Histories', 'Frequency Response Function for Base Excitation', 'Power Spectral Density Response for Base Excitation'};
            app.SelectAnalysisNastranListBox.Value = 'Normal Modes Eigenvalues & Eigenvectors';
            app.AnalyzeButtonNastran = uibutton(app.NastranToolboxTab, 'push');
            app.AnalyzeButtonNastran.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonNastranPushed, true);
            app.AnalyzeButtonNastran.Text = 'Analyze';
            app.TransferFunctionsTab = uitab(app.TabGroup);
            app.TransferFunctionsTab.Title = 'Transfer Functions';
            app.AnalyzeButtonTrans = uibutton(app.TransferFunctionsTab, 'push');
            app.AnalyzeButtonTrans.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonTransPushed, true);
            app.AnalyzeButtonTrans.Text = 'Analyze';
            app.SelectAnalysisListBoxLabel_2 = uilabel(app.TransferFunctionsTab);
            app.SelectAnalysisListBoxLabel_2.Text = 'Select Analysis';
            app.SelectAnalysisTransListBox = uilistbox(app.TransferFunctionsTab);
            app.SelectAnalysisTransListBox.Items = {'Transfer Functions from Modes', 'Response from Input Time History and Transfer Function', ''};
            app.SelectAnalysisTransListBox.Value = 'Transfer Functions from Modes';
            app.MiscellaneousTab = uitab(app.TabGroup);
            app.MiscellaneousTab.Title = 'Miscellaneous';
            app.SelectAnalysisListBoxLabel = uilabel(app.MiscellaneousTab);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisMiscListBox = uilistbox(app.MiscellaneousTab);
            app.SelectAnalysisMiscListBox.Items = {'Damping Coefficient Matrix from Modal Damping', 'Stress-Velocity Relationship', 'Dunkerley Method', 'Area Moment of Inertia  ', ''};
            app.SelectAnalysisMiscListBox.Value = 'Damping Coefficient Matrix from Modal Damping';
            app.AnalyzeButtonMisc = uibutton(app.MiscellaneousTab, 'push');
            app.AnalyzeButtonMisc.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonMiscPushed, true);
            app.AnalyzeButtonMisc.Text = 'Analyze';
            app.structural_dynamics_mainver12byTomIrvineLabel = uilabel(app.UIStructuralDynamicsMainUIFigure);
            app.structural_dynamics_mainver12byTomIrvineLabel.Text = 'structural_dynamics_main     ver 1.2  by Tom Irvine';
            app.TabsLocationButtonGroup = uibuttongroup(app.UIStructuralDynamicsMainUIFigure);
            app.TabsLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TabsLocationButtonGroupSelectionChanged, true);
            app.TabsLocationButtonGroup.Title = 'Tabs Location';
            app.TopButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.TopButton.Text = 'Top';
            app.RightButton = uitogglebutton(app.TabsLocationButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            % Show the figure after all components are created
            app.UIStructuralDynamicsMainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = structural_dynamics_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIStructuralDynamicsMainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIStructuralDynamicsMainUIFigure)
        end
    end
end