classdef tapered_rod_fea_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        taperedrodfeaUIFigure           matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryTab                     matlab.ui.container.Tab
        MasslbmEditField                matlab.ui.control.EditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        PointMassatRightEndListBox      matlab.ui.control.ListBox
        PointMassatRightEndListBoxLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.EditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        RightDiameterinEditField        matlab.ui.control.EditField
        RightDiameterinEditFieldLabel   matlab.ui.control.Label
        LeftDiameterinEditField         matlab.ui.control.EditField
        LeftDiameterinEditFieldLabel    matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        MaterialTab                     matlab.ui.container.Tab
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        ModalAnalysisTab                matlab.ui.container.Tab
        UITable_fn                      matlab.ui.control.Table
        SelectModeforPlotListBox        matlab.ui.control.ListBox
        SelectModeforPlotListBoxLabel   matlab.ui.control.Label
        PlotModeShapeButton             matlab.ui.control.Button
        CalculateNaturalFrequenciesButton  matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SineForceTab                    matlab.ui.container.Tab
        MagnitudePhasedegLabel          matlab.ui.control.Label
        NumberofModestoIncludeEditField  matlab.ui.control.EditField
        NumberofModestoIncludeLabel     matlab.ui.control.Label
        UITable_sineforce               matlab.ui.control.Table
        ForceFreqHzEditField            matlab.ui.control.EditField
        ForceFreqHzEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        ForcedofnumberEditField         matlab.ui.control.EditField
        ForcedofnumberEditFieldLabel    matlab.ui.control.Label
        ApplyForceInputButton           matlab.ui.control.Button
        SettoFirstfnButton              matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        StiffnessArrayEditField         matlab.ui.control.EditField
        StiffnessArrayEditFieldLabel    matlab.ui.control.Label
        MassArrayEditField              matlab.ui.control.EditField
        MassArrayEditFieldLabel         matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        tapered_rod_fea_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iu   % Description
        imat 
        L
        DL
        DR
        E
        rho
        LBC
        RBC

        dm  % Damping

        fn
        omega
        ModeShapes
        MST
        PlotModeShapes
        point_mass

        stiff
        mass

        num
        NE

        s1
        s2
        s3
        s4
        s5
        s6

        h
        xx

        d_f_mag
        v_f_mag
        a_f_mag
        stress_f_mag

    end
    
    methods (Access = private)

        function ChangeRightBC(app)

            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Free'))
                app.LBC=2;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Free'))
                app.RBC=2;                
            end  

            app.MasslbmEditField.Visible='off';
            app.MasslbmEditFieldLabel.Visible='off';

            if(app.RBC==1) % fixed
                app.PointMassatRightEndListBox.Visible='off';
                app.PointMassatRightEndListBoxLabel.Visible='off';                
            else % free
                app.PointMassatRightEndListBox.Visible='on';
                app.PointMassatRightEndListBoxLabel.Visible='on';               
            end    

            PointMass(app);  
        end

        function PointMass(app)   
            Items = char(app.PointMassatRightEndListBox.Items);
            im=app.PointMassatRightEndListBox.Value;
            [app.point_mass]=find_index(Items,im);

            if(app.point_mass==1)
                app.MasslbmEditField.Visible='off';
                app.MasslbmEditFieldLabel.Visible='off';
            else
                app.MasslbmEditField.Visible='on';
                app.MasslbmEditFieldLabel.Visible='on';            
            end

            if(app.iu==1)
                app.MasslbmEditFieldLabel.Text='Mass (lbm)';
            else
                app.MasslbmEditFieldLabel.Text='Mass (kg)';            
            end
        end

        function ChangeDamping(app)
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else
                app.UITable.RowName={'Mode 1'; 'Mode 2'; 'Mode 3';'Mode 4';'Mode 5';'Mode 6'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10;10;10;10;10;10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05;0.05;0.05;0.05;0.05;0.05};                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style

        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end     
        end

        function Unit(app)
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im); 

            ChangeMaterials(app);

            if(app.iu==1)
                app.LengthinEditFieldLabel.Text=sprintf('Length (in)');
                app.LeftDiameterinEditFieldLabel.Text=sprintf('Left Diameter (in)');
                app.RightDiameterinEditFieldLabel.Text=sprintf('Right Diameter (in)');
                app.UITable_sineforce.ColumnName={ 'dof','x (in)','Disp (in)','Vel (ips)','Accel (G)','Stress (psi)'};
                
            else
                app.LengthinEditFieldLabel.Text=sprintf('Length (m)');
                app.LeftDiameterinEditFieldLabel.Text=sprintf('Left Diameter (mm)');
                app.RightDiameterinEditFieldLabel.Text=sprintf('Right Diameter (mm)');   
                app.UITable_sineforce.ColumnName={ 'dof','x (m)','Disp (mm)','Vel (m/sec)','Accel (G)','Stress (Pa)'};
            end

            PointMass(app);

        end    

        function Clearfn(app)
            app.SelectModeforPlotListBox.Visible='off';
            app.SelectModeforPlotListBoxLabel.Visible='off';
            app.PlotModeShapeButton.Enable='off';
            app.UITable_fn.Visible='off';
        end    

        function ChangeMaterials(app)

            Clearfn(app);

            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im); 

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [elastic_modulus,mass_density,~]=six_materials(app.iu,app.imat);

            app.E=elastic_modulus;
            app.rho=mass_density;            
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='app.mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';          
            else
                app.MassDensitylbmin3EditFieldLabel.Text='app.mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
            end    
           
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            Clearfn(app);
            ChangeDamping(app);
            Unit(app);
            ChangeMaterials(app);
            ChangeRightBC(app);
            PointMass(app);

            app.UITable_sineforce.ColumnFormat={'char','char','char','char','char','char'};
            app.UITable_sineforce.ColumnWidth={46,65,85,85,85,85};

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_sineforce,s); % adjust the style     
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)

            ChangeMaterials(app);    
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Unit(app);         
        end

        % Button pushed function: CalculateNaturalFrequenciesButton
        function CalculateNaturalFrequenciesButtonPushed(app, event)
            app.L=str2num(app.LengthinEditField.Value);
            app.E=str2num(app.ElasticModuluspsiEditField.Value);
            app.rho=str2num(app.MassDensitylbmin3EditField.Value);

            app.DL=str2num(app.LeftDiameterinEditField.Value);
            app.DR=str2num(app.RightDiameterinEditField.Value);
            
            % app.BeamLengthinEditField.Value=app.L;
            % app.BeamLengthinEditField_dforce.Value=app.L;

            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
                app.DL=app.DL/1000;
                app.DR=app.DR/1000;
            end
            
            if(isempty(app.L) || app.L==0)
                warndlg('Enter Geometry first');
                return;
            end
            if(isempty(app.E) || app.E==0)  
                warndlg('Enter Material first');
                return;
            end            
            if(isempty(app.rho) || app.rho==0)  
                warndlg('Enter Material first');
                return;
            end            
            
            nL=app.LeftBCListBox.Value;
            nR=app.RightBCListBox.Value;            
            
            if(contains(nL,'Fixed'))
                app.LBC=1;
            end
            if(contains(nL,'Free'))
                app.LBC=2;
            end 
            if(contains(nR,'Fixed'))
                app.RBC=1;                
            end
            if(contains(nR,'Free'))
                app.RBC=2;                
            end    

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            A0=pi*(app.DL)^2/4;
            AL=pi*(app.DR)^2/4;
            alpha=(AL/A0)-1;
            
            app.NE=str2num(app.NumberofElementsEditField.Value);
            app.num=app.NE+1;
            
            app.h=app.L/app.NE;

            app.xx=(1:app.num)*app.h-app.h;
            app.xx=fix_size(app.xx);
            
            app.stiff=zeros(app.num,app.num);
            app.mass=zeros(app.num,app.num);
            
            KK=(alpha*app.h)/(2*app.L);
            MM=(alpha*app.h/app.L);
            
            for j=1:app.NE
               
                sL=(1+KK*(2*j-1))*[1 -1; -1  1 ];
            
                m1=[4 2; 2 4];
                m2=[4*j-1 2*j-1; 2*j-1 4*j-3];
                mL=m1+MM*m2;
                ir1=j;
                ir2=ir1+1;
                ic1=ir1;
                ic2=ir2;
            
                app.stiff(ir1,ic1)=app.stiff(ir1,ic1)+sL(1,1);
                app.stiff(ir1,ic2)=app.stiff(ir1,ic2)+sL(1,2);    
                app.stiff(ir2,ic1)=app.stiff(ir1,ic2);
                app.stiff(ir2,ic2)=app.stiff(ir2,ic2)+sL(2,2); 
            
                app.mass(ir1,ic1)=app.mass(ir1,ic1)+mL(1,1);
                app.mass(ir1,ic2)=app.mass(ir1,ic2)+mL(1,2);    
                app.mass(ir2,ic1)=app.mass(ir1,ic2);
                app.mass(ir2,ic2)=app.mass(ir2,ic2)+mL(2,2);     
            
            end
            
            app.stiff=app.stiff*(app.E*A0/app.h);
            app.mass=app.mass*(app.rho*A0*app.h/12);
            
            
            if(app.RBC==1)
                app.stiff(end,:)=[];
                app.stiff(:,end)=[];    
                app.mass(end,:)=[];
                app.mass(:,end)=[];            
            end

            if(app.LBC==1)
                app.stiff(1,:)=[];
                app.stiff(:,1)=[];    
                app.mass(1,:)=[];
                app.mass(:,1)=[];
            end


            if(app.RBC==2 && app.point_mass==2)
                PM=str2num(app.MasslbmEditField.Value);
                if(app.iu==1)
                    PM=PM/386;
                end    
                app.mass(end,end)=PM;
            end


            ijk=3;
            
            [app.fn,app.omega,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiff,app.mass,ijk);
            
            % MM=[0; app.ModeShapes(:,1)];

            for i=1:length(app.fn)
       
                data_s{i,1}=sprintf('%d',i);
                data_s{i,2}=sprintf('%8.4g',app.fn(i));

            end   
                
            app.UITable_fn.Data=data_s;
            app.UITable_fn.Visible='on';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_fn,s) % adjust the style

            app.PlotModeShapes=app.ModeShapes;
            
            if(app.RBC==1)
                app.PlotModeShapes(end,:)=0;
            end    
            if(app.LBC==1)
                sz=size(app.PlotModeShapes);
                zz=zeros(1,sz(2));
                app.PlotModeShapes=[ zz; app.PlotModeShapes];
            end 

            app.s1=sprintf('%8.4g',app.fn(1));
            app.s2=sprintf('%8.4g',app.fn(2));
            app.s3=sprintf('%8.4g',app.fn(3));
            app.s4=sprintf('%8.4g',app.fn(4));
            app.s5=sprintf('%8.4g',app.fn(5));
            app.s6=sprintf('%8.4g',app.fn(6));
            app.SelectModeforPlotListBox.Items={app.s1;app.s2;app.s3;app.s4;app.s5;app.s6};
            app.SelectModeforPlotListBox.Visible='on';
            app.SelectModeforPlotListBoxLabel.Visible='on';
            ss=sprintf('Select Mode for Plot \n fn (Hz)');
            app.SelectModeforPlotListBoxLabel.Text=ss;
            app.PlotModeShapeButton.Enable='on';
            app.PlotModeShapeButton.Visible='on';            
   

            for i = 1:app.num
                data_s{i,1} =sprintf('%d',i); 
                data_s{i,2} =sprintf('%8.4g',(i-1)*app.h);  
                data_s{i,3} = ' ';
                data_s{i,4} = ' ';
                data_s{i,5} = ' ';
                data_s{i,6} = ' ';   
            end

            app.UITable_sineforce.Data=data_s; 

        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            Clearfn(app);
        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
       
            Clearfn(app);
        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: LeftDiameterinEditField
        function LeftDiameterinEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changed function: RightDiameterinEditField
        function RightDiameterinEditFieldValueChanged(app, event)
           
            Clearfn(app);
        end

        % Value changed function: LengthinEditField
        function LengthinEditFieldValueChanged(app, event)
            
            Clearfn(app);
        end

        % Value changing function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanging(app, event)
            
            Clearfn(app);
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)

            Items = char(app.SelectModeforPlotListBox.Items);
            im=app.SelectModeforPlotListBox.Value;
            [ijk]=find_index(Items,im); 

            figure(ijk)
            plot(app.xx,app.PlotModeShapes(:,ijk));
            grid on;
            if(app.iu==1)
                xlabel('x (in)');
            else
                xlabel('x (m)');            
            end    

            ylabel('Normalized Modal Displacement');
            
            sss=sprintf('Mode %d   fn=%8.4g Hz',ijk,app.fn(ijk));
            
            title(sss);

            assignin('base','mode1', [app.xx app.PlotModeShapes(:,1)]);

        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
            
            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n=6;
            
            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
  
                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    v=1/(2*Q{1});
                else  
                    vd=app.UITable.Data;
                    v=vd{1};
                end   
                app.dm=ones(n,1)*v;
                
            else

                if(contains(nmn,'Amplification')) 
                    Q=app.UITable.Data;
                    for i=1:length(Q)
                        v=1/(2*Q{i});
                        app.dm(i)=v;
                    end
                else  
                    vd=app.UITable.Data;           
                    for i=1:length(vd)
                        v=vd{i};
                        app.dm(i)=v;
                    end            
                end   
            end
        end

        % Button pushed function: SettoFirstfnButton
        function SettoFirstfnButtonPushed(app, event)
            app.ForceFreqHzEditField.Value=sprintf('%8.5g',app.fn(1));
        end

        % Button pushed function: ApplyForceInputButton
        function ApplyForceInputButtonPushed(app, event)
            
            fprintf('\n\n * * * * * * * * * \n\n');

            num_columns=str2num(app.NumberofModestoIncludeEditField.Value);

            k=str2num(app.ForcedofnumberEditField.Value);
            amp =str2num(app.AmplitudeEditField.Value);
            freq =str2num(app.ForceFreqHzEditField.Value);


            if(num_columns>app.num)
                num_columns=app.num;
                app.NumberofModestoIncludeEditField.Value=sprintf('%d',app.num);
            end    

            if(amp<1.0e-06)
                warndlg('Enter Force > 0 ')
                return;    
            end
                             
            fprintf('\n Applied Force \n');
            
            if(app.iu==1)
                fprintf('   %8.4g lbf  %8.4g Hz\n\n',amp,freq);
            else
                fprintf('   %8.4g N  %8.4g Hz\n\n',amp,freq);
            end

            nrb=0;  % leave as zero
    
            QE=app.PlotModeShapes;
            fnv=app.fn;
            dampv=app.dm;
                
            nf=1;
                
            tpi=2*pi;

            omn=tpi*fnv;
            omn2=omn.*omn;
    
            om=2*pi*freq;
            omega2=om^2;

            app.d_f_mag=zeros(app.num,1);
            app.v_f_mag=zeros(app.num,1);
            app.a_f_mag=zeros(app.num,1);

            H_disp_force=zeros(app.num,1);
            H_velox_force=zeros(app.num,1);
            H_accel_force=zeros(app.num,1);

            h_disp_complex=zeros(2,1);

            for i=1:app.num

                    iam=1;
                    [H_disp_force(i)]=...
                    transfer_core_np(freq,fnv,dampv,QE,omn2,om,omega2,iam,nf,num_columns,i,k,nrb);

                    H_disp_force(i)=amp*H_disp_force(i);

                    app.d_f_mag(i)=abs(H_disp_force(i));
                    h_disp_complex(i)=H_disp_force(i);
          
                    iam=2;
                    [H_velox_force(i)]=...
                    transfer_core_np(freq,fnv,dampv,QE,omn2,om,omega2,iam,nf,num_columns,i,k,nrb);
                   
                    H_velox_force(i)=amp*H_velox_force(i);

                    abs(H_velox_force(i));
                    app.v_f_mag(i)=abs(H_velox_force(i));
    
                    iam=3;
                    [H_accel_force(i)]=...
                    transfer_core_np(freq,fnv,dampv,QE,omn2,om,omega2,iam,nf,num_columns,i,k,nrb);
    
                    if(app.iu==1)
                        scale=386;
                    else
                        scale=9.81;
                    end
        
                    H_accel_force(i)=amp*H_accel_force(i)/scale;
        
                    app.a_f_mag(i)=abs(H_accel_force(i));

            end


            H_strain_force=zeros(app.num,1);

            x1=app.xx(1);
            x2=app.xx(2);
            x3=app.xx(3);
            y1=H_disp_force(1);
            y2=H_disp_force(2);
            y3=H_disp_force(3);
            x=x1;
            [~,yd]=quadratic_interpolation_function(x1,y1,x2,y2,x3,y3,x);
            H_strain_force(1)=yd;            

            for i=2:app.num-1
                x1=app.xx(i-1);
                x2=app.xx(i);
                x3=app.xx(i+1);
                y1=H_disp_force(i-1);
                y2=H_disp_force(i);
                y3=H_disp_force(i+1);
                x=x2;
                [~,yd]=quadratic_interpolation_function(x1,y1,x2,y2,x3,y3,x);
                H_strain_force(i)=yd;
            end

            x1=app.xx(app.num-2);
            x2=app.xx(app.num-1);
            x3=app.xx(app.num);
            y1=H_disp_force(app.num-2);
            y2=H_disp_force(app.num-1);
            y3=H_disp_force(app.num);
            x=x3;
            [~,yd]=quadratic_interpolation_function(x1,y1,x2,y2,x3,y3,x);
            H_strain_force(app.num)=yd;  

            H_stress_force=app.E*H_strain_force;

            app.stress_f_mag=abs(H_stress_force);

            fprintf('\n Displacement \n');

            if(app.iu==1)
                fprintf(' dof   x(in)   Disp(in)  Phase(deg)\n');
            else
                fprintf(' dof   x(in)   Disp(mm)  Phase(deg)\n');
            end

            for i=1:app.num
                ph=angle(H_disp_force(i))*180/pi;
                if(app.iu==1)
                    fprintf(' %d %8.4g   %7.2e %8.4g\n',i,app.xx(i),app.d_f_mag(i),ph);
                else
                    fprintf(' %d %8.4g   %7.2e %8.4g\n',i,app.xx(i),app.d_f_mag(i)*1000,ph);
                end   
            end                

            fprintf('\n Velocity \n');

            if(app.iu==1)
                fprintf(' dof   x(in)   Vel(ips)  Phase(deg)\n');
            else
                fprintf(' dof   x(in)   Vel(m/sec)  Phase(deg)\n');
            end

            for i=1:app.num
                ph=angle(H_velox_force(i))*180/pi;
                fprintf(' %d %8.4g %8.4g %8.4g\n',i,app.xx(i),app.v_f_mag(i),ph);
            end    

            fprintf('\n Acceleration \n');
            fprintf(' dof   x(in)   Accel(G)  Phase(deg)\n');
   
            for i=1:app.num
                ph=angle(H_accel_force(i))*180/pi;
                fprintf(' %d %8.4g %8.4g %8.4g\n',i,app.xx(i),app.a_f_mag(i),ph);
            end             

            fprintf('\n Stress \n');

            if(app.iu==1)
                fprintf(' dof   x(in)   Stress(psi)  Phase(deg)\n');
            else
                fprintf(' dof   x(in)   Stress(Pa)  Phase(deg)\n');
            end

            for i=1:app.num
                ph=angle(H_stress_force(i))*180/pi;
                fprintf(' %d %8.4g %8.4g %8.4g\n',i,app.xx(i),app.stress_f_mag(i),ph);
            end 

            fprintf('\n Peak Values \n\n');

            [CD,ID]=max(app.d_f_mag);
            [CV,IV]=max(app.v_f_mag);
            [CA,IA]=max(app.a_f_mag);
            [CS,IS]=max(app.stress_f_mag);

            if(app.iu==1)
                fprintf(' Displacement = %8.4g in at dof %d \n',CD,ID);
            else
                fprintf(' Displacement = %8.4g mm at dof %d \n',CD*1000,ID);                
            end           
            if(app.iu==1)
                fprintf(' Velocity = %8.4g ips at dof %d \n',CV,IV);
            else
                fprintf(' Velocity = %8.4g m/sec at dof %d \n',CV,IV);                
            end

            fprintf(' Acceleration = %8.4g G at dof %d \n',CA,IA);

            if(app.iu==1)
                fprintf(' Stress = %8.4g psi at dof %d at %8.4g\n',CS,IS,app.xx(IS));
            else
                fprintf(' Stress = %8.4g Pa at dof %d at %8.4g\n',CS,IS,app.xx(IS));                
            end  

            c=sqrt(app.E/app.rho);

            rhoc=app.rho*c;

            K=(CS/CV)/rhoc;

            fprintf('\n\n K=%8.3g \n\n',K);

            for i=1:app.num
                ss{i,1}=sprintf('%d',i);
                ss{i,2}=sprintf('%8.4g',app.xx(i));
                ss{i,3}=sprintf('%8.4g',app.d_f_mag(i));
                ss{i,4}=sprintf('%8.4g',app.v_f_mag(i));
                ss{i,5}=sprintf('%8.4g',app.a_f_mag(i));
                ss{i,6}=sprintf('%8.4g',app.stress_f_mag(i));
            end

            app.UITable_sineforce.Data=ss;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            output_stiff=app.StiffnessArrayEditField.Value;
            output_mass=app.MassArrayEditField.Value;

            assignin('base',output_stiff, app.stiff);
            assignin('base',output_mass, app.mass);

            msgbox('Arrays saved');
        end

        % Value changed function: PointMassatRightEndListBox
        function PointMassatRightEndListBoxValueChanged(app, event)
    
            PointMass(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.taperedrodfeaUIFigure = uifigure('Visible', 'off');
            app.taperedrodfeaUIFigure.Name = 'tapered rod fea';
            app.tapered_rod_fea_aver10byTomIrvineLabel = uilabel(app.taperedrodfeaUIFigure);
            app.tapered_rod_fea_aver10byTomIrvineLabel.Text = 'tapered_rod_fea_a   ver 1.2   by Tom Irvine         Tapered Rod, Circular Cross-section, Finite Element Analysis';
            app.TabGroup = uitabgroup(app.taperedrodfeaUIFigure);
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.UnitsListBoxLabel = uilabel(app.GeometryTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.GeometryTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.LengthinEditFieldLabel = uilabel(app.GeometryTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryTab, 'text');
            app.LengthinEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinEditFieldValueChanged, true);
            app.LeftDiameterinEditFieldLabel = uilabel(app.GeometryTab);
            app.LeftDiameterinEditFieldLabel.Text = 'Left Diameter (in)';
            app.LeftDiameterinEditField = uieditfield(app.GeometryTab, 'text');
            app.LeftDiameterinEditField.ValueChangedFcn = createCallbackFcn(app, @LeftDiameterinEditFieldValueChanged, true);
            app.RightDiameterinEditFieldLabel = uilabel(app.GeometryTab);
            app.RightDiameterinEditFieldLabel.Text = 'Right Diameter (in)';
            app.RightDiameterinEditField = uieditfield(app.GeometryTab, 'text');
            app.RightDiameterinEditField.ValueChangedFcn = createCallbackFcn(app, @RightDiameterinEditFieldValueChanged, true);
            app.LeftBCListBoxLabel = uilabel(app.GeometryTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.GeometryTab);
            app.LeftBCListBox.Items = {'Fixed', 'Free'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.Value = 'Fixed';
            app.RightBCListBoxLabel = uilabel(app.GeometryTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.GeometryTab);
            app.RightBCListBox.Items = {'Fixed', 'Free'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.Value = 'Free';
            app.Image = uiimage(app.GeometryTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tapered_beam.jpg');
            app.NumberofElementsEditFieldLabel = uilabel(app.GeometryTab);
            app.NumberofElementsEditFieldLabel.Text = {'Number of '; 'Elements'};
            app.NumberofElementsEditField = uieditfield(app.GeometryTab, 'text');
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.NumberofElementsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanging, true);
            app.NumberofElementsEditField.Value = '100';
            app.PointMassatRightEndListBoxLabel = uilabel(app.GeometryTab);
            app.PointMassatRightEndListBoxLabel.Text = 'Point Mass at Right End';
            app.PointMassatRightEndListBox = uilistbox(app.GeometryTab);
            app.PointMassatRightEndListBox.Items = {'No', 'Yes'};
            app.PointMassatRightEndListBox.ValueChangedFcn = createCallbackFcn(app, @PointMassatRightEndListBoxValueChanged, true);
            app.PointMassatRightEndListBox.Value = 'No';
            app.MasslbmEditFieldLabel = uilabel(app.GeometryTab);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.GeometryTab, 'text');
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.MaterialListBoxLabel = uilabel(app.MaterialTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.MaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.MaterialTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.MaterialTab, 'text');
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.MaterialTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.MaterialTab, 'text');
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.CalculateNaturalFrequenciesButton = uibutton(app.ModalAnalysisTab, 'push');
            app.CalculateNaturalFrequenciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNaturalFrequenciesButtonPushed, true);
            app.CalculateNaturalFrequenciesButton.Text = 'Calculate Natural Frequencies';
            app.PlotModeShapeButton = uibutton(app.ModalAnalysisTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.SelectModeforPlotListBoxLabel = uilabel(app.ModalAnalysisTab);
            app.SelectModeforPlotListBoxLabel.Text = 'Select Mode for Plot';
            app.SelectModeforPlotListBox = uilistbox(app.ModalAnalysisTab);
            app.UITable_fn = uitable(app.ModalAnalysisTab);
            app.UITable_fn.ColumnName = {'Mode'; 'Natural Freq (Hz)'};
            app.UITable_fn.RowName = {};
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.SineForceTab = uitab(app.TabGroup);
            app.SineForceTab.Title = 'Sine Force';
            app.SettoFirstfnButton = uibutton(app.SineForceTab, 'push');
            app.SettoFirstfnButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstfnButtonPushed, true);
            app.SettoFirstfnButton.Text = 'Set to First fn';
            app.ApplyForceInputButton = uibutton(app.SineForceTab, 'push');
            app.ApplyForceInputButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyForceInputButtonPushed, true);
            app.ApplyForceInputButton.Text = 'Apply Force Input';
            app.ForcedofnumberEditFieldLabel = uilabel(app.SineForceTab);
            app.ForcedofnumberEditFieldLabel.Text = 'Force dof number';
            app.ForcedofnumberEditField = uieditfield(app.SineForceTab, 'text');
            app.AmplitudeEditFieldLabel = uilabel(app.SineForceTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineForceTab, 'text');
            app.ForceFreqHzEditFieldLabel = uilabel(app.SineForceTab);
            app.ForceFreqHzEditFieldLabel.Text = 'Force Freq (Hz)';
            app.ForceFreqHzEditField = uieditfield(app.SineForceTab, 'text');
            app.UITable_sineforce = uitable(app.SineForceTab);
            app.UITable_sineforce.ColumnName = {'dof'; 'x'; 'Disp'; 'Velocity'; 'Accel'; 'Stress'};
            app.UITable_sineforce.RowName = {};
            app.UITable_sineforce.ColumnEditable = [false false false false false false];
            app.NumberofModestoIncludeLabel = uilabel(app.SineForceTab);
            app.NumberofModestoIncludeLabel.Text = {'Number of Modes '; 'to Include'};
            app.NumberofModestoIncludeEditField = uieditfield(app.SineForceTab, 'text');
            app.NumberofModestoIncludeEditField.Value = '1';
            app.MagnitudePhasedegLabel = uilabel(app.SineForceTab);
            app.MagnitudePhasedegLabel.Text = 'Magnitude & Phase (deg)';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MassArrayEditFieldLabel = uilabel(app.ExportTab);
            app.MassArrayEditFieldLabel.Text = 'Mass Array';
            app.MassArrayEditField = uieditfield(app.ExportTab, 'text');
            app.MassArrayEditField.Value = 'tapered_mass';
            app.StiffnessArrayEditFieldLabel = uilabel(app.ExportTab);
            app.StiffnessArrayEditFieldLabel.Text = 'Stiffness Array';
            app.StiffnessArrayEditField = uieditfield(app.ExportTab, 'text');
            app.StiffnessArrayEditField.Value = 'tapered_stiffness';
            app.ReturnButton = uibutton(app.taperedrodfeaUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.taperedrodfeaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tapered_rod_fea_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.taperedrodfeaUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.taperedrodfeaUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.taperedrodfeaUIFigure)
        end
    end
end