classdef temporal_moments_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        temporalmomentsUIFigure       matlab.ui.Figure
        ResultsReadOnlyTextArea       matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel  matlab.ui.control.Label
        SelectInputArrayListBox       matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton              matlab.ui.control.Button
        text1                         matlab.ui.control.Label
        listbox_method                matlab.ui.control.ListBox
        CalculateButton               matlab.ui.control.Button
        TimeShiftsecEditField         matlab.ui.control.EditField
        TimeShiftsecEditFieldLabel    matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        temporal_moments_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = public)

       function clear(app)
            app.ResultsReadOnlyTextArea.Value='';
       end    
        
       function ClosePlots(app)
             for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        
        function update(app)
            clear(app);
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            ClosePlots(app);
            clear(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            fig_num=1;
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
            
            t=THM(:,1);
            a=THM(:,2);
            
            ref=str2num(app.TimeShiftsecEditField.Value);
            
            
            figure(fig_num);
            plot(t,a);
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            title('Input Time History');
            
            %%%%%
            
              amp=double(THM(:,2));
                tim=double(THM(:,1));
                n = length(amp);
            %disp(' mean values ')
               
                mx=max(amp);
                mi=min(amp);
                TT=t;
                
                [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
                [tt_max,tt_min,mx,mi]=max_min_times(TT,amp);


            %1
                disp(' ')
                disp(' Time Stats ')
                tmx=max(tim);
                tmi=min(tim);
                duration=(tmx-tmi);
            % 
                out3 = sprintf('   start  = %g sec    end = %g sec   duration = %g sec  ',tmi,tmx,duration);
                disp(out3)
            %
                dt=duration/(n-1);
                out5 = sprintf('\n number of samples = %d  ',n);
                disp(out5)
            %
                clear t;
                t=tim;
                disp(' ')
                disp(' Time Step ');
                clear difft;
                difft=diff(t);
                dtmin=min(difft);
                dtmax=max(difft);
            %
                out4 = sprintf(' dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf(' dt     = %8.4g sec  ',dt);
                out6 = sprintf(' dtmax  = %8.4g sec  ',dtmax);
                disp(out4)
                disp(out5)
                disp(out6)
            %
                disp(' ')
                disp(' Sample Rate ');
                out4 = sprintf(' srmin  = %8.4g samples/sec  ',1/dtmax);
                out5 = sprintf(' sr     = %8.4g samples/sec  ',1/dt);
                out6 = sprintf(' srmax  = %8.4g samples/sec  ',1/dtmin);
                disp(out4)
                disp(out5)
                disp(out6)
                clear t;
            %
                disp(' ')
                disp(' Amplitude Stats ')
                 out0 = sprintf(' number of points = %d ',n);
                out1 = sprintf(' mean = %8.4g    std = %8.4g    rms = %8.4g ',mu,sd,rms);
                out2a = sprintf(' max  = %9.4g  at  = %8.4g sec             ',mx,tt_max);
                out2b = sprintf(' min  = %9.4g  at  = %8.4g sec            \n',mi,tt_min);
                disp(out0);
                disp(out1);
                disp(out2a);
                disp(out2b);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            a2=zeros(n,1);
            %
            for i=1:n
                a2(i)=amp(i)^2;
            end
            %
            m0=0;
            m1=0;
            m2=0;
            m3=0;
            m4=0;
            %
            for i=1:n
                    ta=(tim(i)-ref);
                    
                    a2i=a2(i);
                    
                    m0=m0+a2i;        
                    m1=m1+ta*a2i;
                    m2=m2+ta^2*a2i;
                    m3=m3+ta^3*a2i; 
                    m4=m4+ta^4*a2i;          
            end    
            %
            m0=m0*dt;
            m1=m1*dt;
            m2=m2*dt;
            m3=m3*dt;
            m4=m4*dt;
            %
            E=m0;
            D=sqrt( (m2/m0) - (m1/m0)^2 );
            %
            Ae=sqrt(E/D);
            T=m1/m0;
            
            ss=(m3/m0)-3*(m1*m2/m0^2)+2*(m1/m0)^3;
            St=(ss)^(1/3);
            
            S=St/D;
            
            kk=(m4/m0)-4*(m1*m3/m0^2)+6*(m1^2*m2/m0^3)-3*(m1/m0)^4;
            Kt=(kk)^(1/4);
            
            K=Kt/D;
            %
            disp(' ');
            disp('               Central Moments ');
            disp('  ');
            %
            out1=sprintf('                Energy E  = %8.4g G^2 sec',E);
            out2=sprintf(' Root energy amplitude Ae = %8.4g ',Ae);
            out3=sprintf('          Central time T  = %8.4g sec',T);
            out4=sprintf('          RMS duration D  = %8.4g sec',D);
            out5=sprintf('      Central skewness St = %8.4g sec',St);
            out6=sprintf('   Normalized skewness S  = %8.4g ',S);
            out7=sprintf('      Central kurtosis Kt = %8.4g sec',Kt);
            out8=sprintf('   Normalized kurtosis K  = %8.4g ',K);
            
            %
            disp(out1);
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            disp(out6);
            disp(out7);
            disp(out8);

            ss=sprintf('Central Moments \n\n %s\n%s\n %s\n %s\n\n %s\n %s\n %s\n %s',out1,out2,out3,out4,out5,out6,out7,out8);
            
           app.ResultsReadOnlyTextArea.Value=ss;
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
  
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.temporalmomentsUIFigure = uifigure('Visible', 'off');
            app.temporalmomentsUIFigure.Name = 'temporal moments';
            app.temporal_moments_aver10byTomIrvineLabel = uilabel(app.temporalmomentsUIFigure);
            app.temporal_moments_aver10byTomIrvineLabel.Text = {'temporal_moments_a   ver 1.0   by Tom Irvine         Smallwood metrics for shock data.   '; ''; 'The input time history must have two columns:  time (sec) & accel (G)'};
            app.ReturnButton = uibutton(app.temporalmomentsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TimeShiftsecEditFieldLabel = uilabel(app.temporalmomentsUIFigure);
            app.TimeShiftsecEditFieldLabel.Text = 'Time Shift (sec)';
            app.TimeShiftsecEditField = uieditfield(app.temporalmomentsUIFigure, 'text');
            app.TimeShiftsecEditField.Value = '0';
            app.CalculateButton = uibutton(app.temporalmomentsUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.listbox_method = uilistbox(app.temporalmomentsUIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.temporalmomentsUIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.temporalmomentsUIFigure, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.temporalmomentsUIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.temporalmomentsUIFigure);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ResultsReadOnlyTextAreaLabel = uilabel(app.temporalmomentsUIFigure);
            app.ResultsReadOnlyTextAreaLabel.Text = 'Results, Read Only';
            app.ResultsReadOnlyTextArea = uitextarea(app.temporalmomentsUIFigure);
            app.ResultsReadOnlyTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.temporalmomentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temporal_moments_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.temporalmomentsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.temporalmomentsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.temporalmomentsUIFigure)
        end
    end
end