classdef three_dof_system_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        three_dof_systemUIFigure      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectSystemTab               matlab.ui.container.Tab
        ReturnButton                  matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        SelectButton_2                matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        SelectButton_3                matlab.ui.control.Button
        Image_3                       matlab.ui.control.Image
        SelectButton_4                matlab.ui.control.Button
        Image_4                       matlab.ui.control.Image
        ModalAnalysisTab              matlab.ui.container.Tab
        UnitsListBox                  matlab.ui.control.ListBox
        UnitsListBoxLabel             matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        EnterValuesLabel              matlab.ui.control.Label
        ImageModal                    matlab.ui.control.Image
        CalculateButton               matlab.ui.control.Button
        ResultsTab                    matlab.ui.container.Tab
        MassNormalizedEigenvectorsTextArea  matlab.ui.control.TextArea
        MassNormalizedEigenvectorsTextAreaLabel  matlab.ui.control.Label
        StiffnessMatrixTextArea       matlab.ui.control.TextArea
        StiffnessMatrixTextAreaLabel  matlab.ui.control.Label
        MassMatrixTextArea            matlab.ui.control.TextArea
        MassMatrixTextAreaLabel       matlab.ui.control.Label
        NaturalFrequenciesTextArea    matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        ExportTab                     matlab.ui.container.Tab
        NewmarkBetaEnforcedMotionButton  matlab.ui.control.Button
        ModeShapesEditField           matlab.ui.control.EditField
        ModeShapesEditFieldLabel      matlab.ui.control.Label
        NaturalFrequenciesEditField   matlab.ui.control.EditField
        NaturalFrequenciesEditFieldLabel  matlab.ui.control.Label
        StiffnessEditField            matlab.ui.control.EditField
        StiffnessEditFieldLabel       matlab.ui.control.Label
        MassEditField                 matlab.ui.control.EditField
        MassEditFieldLabel            matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        three_dof_system_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ichoice  % Description
        iu
        K
        M
        fn
        omegan
        ModeShapes
        MST
        pff
        mu
        ku
    end
    
    methods (Access = private)

        function ichoice1(app)
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='m3';
                ss{4,1}='k1';
                ss{5,1}='k2';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';  
                ss{5,2}='';              
                ss{1,3}=app.mu;
                ss{2,3}=app.mu; 
                ss{3,3}=app.mu;
                ss{4,3}=app.ku;
                ss{5,3}=app.ku;  
       
            app.UITable.Data=ss; 
        end 
        function ichoice2(app)
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='m3';
                ss{4,1}='k1';
                ss{5,1}='k2';
                ss{6,1}='k3';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';  
                ss{5,2}='';
                ss{6,2}='';                
                ss{1,3}=app.mu;
                ss{2,3}=app.mu; 
                ss{3,3}=app.mu;
                ss{4,3}=app.ku;
                ss{5,3}=app.ku;  
                ss{6,3}=app.ku;
           
       
            app.UITable.Data=ss; 
        end    
        function ichoice3(app)
    
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='m3';
                ss{4,1}='k1';
                ss{5,1}='k2';
                ss{6,1}='k3';
                ss{7,1}='k4';              
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';  
                ss{5,2}='';   
                ss{6,2}='';  
                ss{7,2}='';              
                ss{1,3}=app.mu;
                ss{2,3}=app.mu; 
                ss{3,3}=app.mu;
                ss{4,3}=app.ku;
                ss{5,3}=app.ku;  
                ss{6,3}=app.ku;
                ss{7,3}=app.ku;          
            app.UITable.Data=ss; 
        end    
        function ichoice4(app)
    
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='m3';
                ss{4,1}='k1';
                ss{5,1}='k2';
                ss{6,1}='k3';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';  
                ss{5,2}='';
                ss{6,2}='';                
                ss{1,3}=app.mu;
                ss{2,3}=app.mu; 
                ss{3,3}=app.mu;
                ss{4,3}=app.ku;
                ss{5,3}=app.ku;  
                ss{6,3}=app.ku;            
       
            app.UITable.Data=ss; 
        end           


        function units(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.mu='lbm';
                app.ku='lbf/in';
            else
                app.mu='N';
                app.ku='N/m'; 
            end

            if(app.ichoice==1)
                ichoice1(app);
            end  
            if(app.ichoice==2)
                ichoice2(app);
            end        
            if(app.ichoice==3)
                ichoice3(app);
            end  
            if(app.ichoice==4)
                ichoice4(app);
            end

        end    
        
        function clear_modal(app)
            app.MassMatrixTextArea.Visible='off';
            app.StiffnessMatrixTextArea.Visible='off';
            app.MassNormalizedEigenvectorsTextArea.Visible='off';
            app.NaturalFrequenciesTextArea.Visible='off';
            app.MassMatrixTextAreaLabel.Visible='off';
            app.StiffnessMatrixTextAreaLabel.Visible='off';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='off';
            app.NaturalFrequenciesTextAreaLabel.Visible='off';            
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear_modal(app);
            app.ichoice=1;
            units(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.ichoice=1;
            app.ImageModal.ImageSource='threedof1_sm.jpg';
 
            ichoice1(app);

            app.TabGroup.SelectedTab=app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            app.ichoice=2;
            app.ImageModal.ImageSource='threedof2_sm.jpg';

            ichoice2(app);
       

            app.TabGroup.SelectedTab=app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            app.ichoice=3;
            app.ImageModal.ImageSource='threedof3_sm.jpg';

            ichoice3(app); 
 

            app.TabGroup.SelectedTab=app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            app.ichoice=4;
            app.ImageModal.ImageSource='threedof4_sm.jpg';

            ichoice4(app);


            app.TabGroup.SelectedTab=app.ModalAnalysisTab;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear_modal(app);

            Y=char(app.UITable.Data);
            sz=size(Y);
            N=round(sz(1)/3); 

            m1=str2num(Y(N+1,:));
            m2=str2num(Y(N+2,:));
            m3=str2num(Y(N+3,:));

            app.M=zeros(3,3);

            try
                app.M(1,1)=m1;
                app.M(2,2)=m2;
                app.M(3,3)=m3;
            catch
                warndlg('Enter mass & stiffness first');
                return;          
            end

            if(app.iu==1)
               app.M=app.M/386.;
            end

            k1=str2num(Y(N+4,:));
            k2=str2num(Y(N+5,:)); 

            if(app.ichoice~=1)
                k3=str2num(Y(N+6,:));       
            end 

            if(app.ichoice==1)
                app.K=[k1 -k1 0; -k1 k1+k2 -k2; 0 -k2 k2];
            end     
            if(app.ichoice==2)
                app.K=[k1+k2 -k2 0; -k2 k2+k3 -k3; 0 -k3 k3];
            end
            if(app.ichoice==3)
  
                k4=str2num(Y(N+7,:)); 

                app.K=[k1+k2 -k2 0; -k2 k2+k3 -k3; 0 -k3 k3+k4];

            end
            if(app.ichoice==4)
                app.K=[k1+k2+k3 -k2 -k3; -k2 k2 0; -k3 0 k3];
            end

            
            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.K,app.M,2);
        
            %
            dof=length(app.fn);
            %
            v=ones(dof,1)';

            if(app.iu==1)
                s1='Mass Matrix (lbf sec^2/in)';
            else
                s1='Mass Matrix (kg)';    
            end
            app.MassMatrixTextAreaLabel.Text=s1;

            m=app.M;
            ss_m=sprintf('%8.4g\t  %8.4g\t  %8.4g\n  %8.4g\t  %8.4g\t  %8.4g\n %8.4g\t  %8.4g\t  %8.4g',m');
            
            k=app.K;
            if(app.iu==1)
                s2='Stiffness Matrix (lbf/in)';
            else
                s2='Stiffness Matrix (N/m)';    
            end
            app.StiffnessMatrixTextAreaLabel.Text=s2; 
            ss_k=sprintf('%8.4g\t  %8.4g\t  %8.4g\n  %8.4g\t  %8.4g\t  %8.4g\n %8.4g\t  %8.4g\t  %8.4g',k');

            e=app.ModeShapes;
            ss_e=sprintf('%8.4g\t  %8.4g\t  %8.4g\n  %8.4g\t  %8.4g\t  %8.4g\n %8.4g\t  %8.4g\t  %8.4g',e');
            app.MassNormalizedEigenvectorsTextArea.Value=ss_e;


                ss_f='            Natural      Participation    Effective Modal';
                
                if(app.iu==1)
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (lbm)';
                else
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (kg)';    
                end    


            ss_f=sprintf('%s\n%s\n',ss_f,ss_ff);
            
            %
            LM=app.MST*app.M*v';
            pf=LM;
            sum=0;
            %    
            mmm=app.MST*app.M*app.ModeShapes;   
            %
            
            app.pff=zeros(dof,1);
            emm=zeros(dof,1);
            
            app.fn(abs(app.fn)<1.0e-05) = 0;
            
            for i=1:dof
                    app.pff(i)=pf(i)/mmm(i,i);
                    emm(i)=pf(i)^2/mmm(i,i);
                    sum=sum+emm(i); 

                   
                        if(app.iu==1)
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i)*386);           
                        else
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t   %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i));            
                        end
          

                    ss_f=sprintf('%s\n%s',ss_f,out1);
            end
            if(app.iu==1)
                out1=sprintf('\n modal mass sum = %8.4g lbm \n',sum*386);
            else
                out1=sprintf('\n modal mass sum = %8.4g kg \n',sum);    
            end

          
                ss_fn=sprintf('%s\n%s\n',ss_f,out1);
      

            app.MassMatrixTextArea.Value=ss_m;
            app.StiffnessMatrixTextArea.Value=ss_k;
            app.NaturalFrequenciesTextArea.Value=ss_fn; 

            app.MassMatrixTextArea.Visible='on';
            app.StiffnessMatrixTextArea.Visible='on';
            app.MassNormalizedEigenvectorsTextArea.Visible='on';
            app.NaturalFrequenciesTextArea.Visible='on';
            app.MassMatrixTextAreaLabel.Visible='on';
            app.StiffnessMatrixTextAreaLabel.Visible='on';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='on';
            app.NaturalFrequenciesTextAreaLabel.Visible='on';   

                      
            app.TabGroup.SelectedTab=app.ResultsTab;

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            
            units(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name_mass=app.MassEditField.Value;
            name_stiff=app.StiffnessEditField.Value;
            name_fn=app.NaturalFrequenciesEditField.Value;
            name_modes=app.ModeShapesEditField.Value;

            assignin('base',name_mass, app.M);            
            assignin('base',name_stiff, app.K);            
            assignin('base',name_fn, app.fn);
            assignin('base',name_modes, app.ModeShapes);
            
            msgbox('Data saved');   
        end

        % Button pushed function: NewmarkBetaEnforcedMotionButton
        function NewmarkBetaEnforcedMotionButtonPushed(app, event)
            nm_mdof_arbitrary_enforced_motion_a;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.three_dof_systemUIFigure = uifigure('Visible', 'off');
            app.three_dof_systemUIFigure.Name = 'three_dof_system';
            app.three_dof_system_aver10byTomIrvineLabel = uilabel(app.three_dof_systemUIFigure);
            app.three_dof_system_aver10byTomIrvineLabel.Text = 'three_dof_system_a   ver 1.1   by Tom Irvine     This script calculates the natural frequencies and mode shapes of a three-degree-of-freedom system';
            app.TabGroup = uitabgroup(app.three_dof_systemUIFigure);
            app.SelectSystemTab = uitab(app.TabGroup);
            app.SelectSystemTab.Title = 'Select System';
            app.Image_4 = uiimage(app.SelectSystemTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'threedof4_sm.jpg');
            app.SelectButton_4 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.Image_3 = uiimage(app.SelectSystemTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'threedof3_sm.jpg');
            app.SelectButton_3 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.Image_2 = uiimage(app.SelectSystemTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'threedof2_sm.jpg');
            app.SelectButton_2 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.Image = uiimage(app.SelectSystemTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'threedof1_sm.jpg');
            app.SelectButton = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ReturnButton = uibutton(app.SelectSystemTab, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.CalculateButton = uibutton(app.ModalAnalysisTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ImageModal = uiimage(app.ModalAnalysisTab);
            app.EnterValuesLabel = uilabel(app.ModalAnalysisTab);
            app.EnterValuesLabel.Text = 'Enter Values';
            app.UITable = uitable(app.ModalAnalysisTab);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UnitsListBoxLabel = uilabel(app.ModalAnalysisTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.ModalAnalysisTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.ResultsTab);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.ResultsTab);
            app.NaturalFrequenciesTextArea.Editable = 'off';
            app.MassMatrixTextAreaLabel = uilabel(app.ResultsTab);
            app.MassMatrixTextAreaLabel.Text = 'Mass Matrix';
            app.MassMatrixTextArea = uitextarea(app.ResultsTab);
            app.StiffnessMatrixTextAreaLabel = uilabel(app.ResultsTab);
            app.StiffnessMatrixTextAreaLabel.Text = 'Stiffness Matrix';
            app.StiffnessMatrixTextArea = uitextarea(app.ResultsTab);
            app.MassNormalizedEigenvectorsTextAreaLabel = uilabel(app.ResultsTab);
            app.MassNormalizedEigenvectorsTextAreaLabel.Text = 'Mass Normalized Eigenvectors';
            app.MassNormalizedEigenvectorsTextArea = uitextarea(app.ResultsTab);
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MassEditFieldLabel = uilabel(app.ExportTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ExportTab, 'text');
            app.MassEditField.Value = 'three_dof_mass';
            app.StiffnessEditFieldLabel = uilabel(app.ExportTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.ExportTab, 'text');
            app.StiffnessEditField.Value = 'three_dof_stiffness';
            app.NaturalFrequenciesEditFieldLabel = uilabel(app.ExportTab);
            app.NaturalFrequenciesEditFieldLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesEditField = uieditfield(app.ExportTab, 'text');
            app.NaturalFrequenciesEditField.Value = 'three_dof_fn';
            app.ModeShapesEditFieldLabel = uilabel(app.ExportTab);
            app.ModeShapesEditFieldLabel.Text = 'Mode Shapes';
            app.ModeShapesEditField = uieditfield(app.ExportTab, 'text');
            app.ModeShapesEditField.Value = 'three_dof_modes';
            app.NewmarkBetaEnforcedMotionButton = uibutton(app.ExportTab, 'push');
            app.NewmarkBetaEnforcedMotionButton.ButtonPushedFcn = createCallbackFcn(app, @NewmarkBetaEnforcedMotionButtonPushed, true);
            app.NewmarkBetaEnforcedMotionButton.Text = 'Newmark-Beta Enforced Motion';
            % Show the figure after all components are created
            app.three_dof_systemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = three_dof_system_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.three_dof_systemUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.three_dof_systemUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.three_dof_systemUIFigure)
        end
    end
end