classdef time_conversion_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        timeconversionUIFigure       matlab.ui.Figure
        InputTimeLabel               matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ResultReadOnlyTextArea       matlab.ui.control.TextArea
        ResultReadOnlyTextAreaLabel  matlab.ui.control.Label
        TimesecEditField             matlab.ui.control.EditField
        TimesecEditFieldLabel        matlab.ui.control.Label
        ConvertListBox               matlab.ui.control.ListBox
        ConvertListBoxLabel          matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        time_conversion_aver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        n % Description
    end
    
    methods (Access = private)
        
        function change(app)
            Items = strtrim(app.ConvertListBox.Items);
            im = strtrim(app.ConvertListBox.Value);
            app.n = find(strcmp(Items, im));

            if(app.n==1)
                app.TimesecEditField.Visible='on';
                app.TimesecEditFieldLabel.Visible='on';
                app.InputTimeLabel.Visible='off';
                app.UITable.Visible='off';
            else
                app.TimesecEditField.Visible='off';
                app.TimesecEditFieldLabel.Visible='off';
                app.InputTimeLabel.Visible='on';
                app.UITable.Visible='on';   
                app.UITable.Data={'','','',''};
                s = uistyle('HorizontalAlignment','center'); % create a style
                addStyle(app.UITable,s) % adjust the style
            end  

            app.ResultReadOnlyTextArea.Value='';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.n==1)
                tsec=str2num(app.TimesecEditField.Value);

                ss=sprintf('Time input = %8.4g sec',tsec);
                
                tdays=floor(tsec/86400);
            %
                residual=tsec-tdays*86400;
                
                thr=floor(residual/3600);
                
                residual=residual-thr*3600;
                
                tmin=floor(residual/60);
                
                residual=residual-tmin*60;
                
                tsec=residual;
            
                if(tdays>365)
                    years=tdays/365.25;
                    ss1=printf('\n %8.4g years  (ref: 365.25 days per year) \n',years);
                    ss=sprintf('%s\n%s',ss,ss1);
                end    
                
                ss2=sprintf('\n %d days %d hr %d min %g sec \n',tdays,thr,tmin,tsec);
                
                ss3=sprintf('\n       %g hr %g min %g sec ',(24*tdays+thr),tmin,tsec);
                
                ss4=sprintf('\n days:hr:min:sec \n\n   %d:%d:%d:%g \n',tdays,thr,tmin,tsec);

                ss=sprintf('%s\n%s\n%s\n%s',ss,ss2,ss3,ss4);
                
            else
                 C=app.UITable.Data;
                
                if(isempty(C{1}))
                    C{1}='0';
                end
                if(isempty(C{2}))
                    C{2}='0';
                end
                if(isempty(C{3}))
                    C{3}='0';
                end
                if(isempty(C{4}))
                    C{4}='0';
                end    
                
                A=char(C);  
                
                B=str2num(A);
                
                days=B(1);
                hr=B(2);
                min=B(3);
                sec=B(4);    
                
                ss1=sprintf(' %g days  %g hrs  %g min %g sec \n',days,hr,min,sec);
                
                ss2=sprintf('\n          %g hrs  %g min %g sec \n',(24*days+hr),min,sec);
                
                total=sec+min*60+hr*3600+days*86400;
                ss3=sprintf('\n total time = %10.4g sec',total);

                ss=sprintf('%s %s %s',ss1,ss2,ss3);
                        
            end

            app.ResultReadOnlyTextArea.Value=ss;
        end

        % Value changed function: TimesecEditField
        function TimesecEditFieldValueChanged(app, event)
            
            app.ResultReadOnlyTextArea.Value='';
        end

        % Value changing function: TimesecEditField
        function TimesecEditFieldValueChanging(app, event)
            
            app.ResultReadOnlyTextArea.Value='';
        end

        % Value changed function: ConvertListBox
        function ConvertListBoxValueChanged(app, event)
  
            change(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.timeconversionUIFigure = uifigure('Visible', 'off');
            app.timeconversionUIFigure.Name = 'time conversion';
            app.ReturnButton = uibutton(app.timeconversionUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.time_conversion_aver10byTomIrvineLabel = uilabel(app.timeconversionUIFigure);
            app.time_conversion_aver10byTomIrvineLabel.Text = 'time_conversion_a   ver 1.0   by Tom Irvine';
            app.CalculateButton = uibutton(app.timeconversionUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConvertListBoxLabel = uilabel(app.timeconversionUIFigure);
            app.ConvertListBoxLabel.Text = 'Convert';
            app.ConvertListBox = uilistbox(app.timeconversionUIFigure);
            app.ConvertListBox.Items = {'sec  to  days:hr:min:sec', 'days:hr:min:sec  to  sec'};
            app.ConvertListBox.ValueChangedFcn = createCallbackFcn(app, @ConvertListBoxValueChanged, true);
            app.ConvertListBox.Value = 'sec  to  days:hr:min:sec';
            app.TimesecEditFieldLabel = uilabel(app.timeconversionUIFigure);
            app.TimesecEditFieldLabel.Text = 'Time (sec)';
            app.TimesecEditField = uieditfield(app.timeconversionUIFigure, 'text');
            app.TimesecEditField.ValueChangedFcn = createCallbackFcn(app, @TimesecEditFieldValueChanged, true);
            app.TimesecEditField.ValueChangingFcn = createCallbackFcn(app, @TimesecEditFieldValueChanging, true);
            app.ResultReadOnlyTextAreaLabel = uilabel(app.timeconversionUIFigure);
            app.ResultReadOnlyTextAreaLabel.Text = 'Result, Read Only';
            app.ResultReadOnlyTextArea = uitextarea(app.timeconversionUIFigure);
            app.ResultReadOnlyTextArea.Editable = 'off';
            app.UITable = uitable(app.timeconversionUIFigure);
            app.UITable.ColumnName = {'Days'; 'Hours'; 'Minutes'; 'Seconds'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true];
            app.InputTimeLabel = uilabel(app.timeconversionUIFigure);
            app.InputTimeLabel.Text = 'Input Time';
            % Show the figure after all components are created
            app.timeconversionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = time_conversion_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.timeconversionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.timeconversionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.timeconversionUIFigure)
        end
    end
end