classdef transfer_functions_from_modes_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        transferfunctionsUIFigure       matlab.ui.Figure
        ResponsedofEditField            matlab.ui.control.EditField
        ResponsedofEditFieldLabel       matlab.ui.control.Label
        ForceInputdofEditField          matlab.ui.control.EditField
        ForceInputdofEditFieldLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DampingTab                      matlab.ui.container.Tab
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        MassStiffnessMethodTab          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CalculationTab                  matlab.ui.container.Tab
        MassArrayListBox                matlab.ui.control.ListBox
        MassArrayListBoxLabel           matlab.ui.control.Label
        StiffnessArrayListBoxLabel      matlab.ui.control.Label
        StiffnessArrayListBox           matlab.ui.control.ListBox
        MassunitLabel                   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        UpdateListsButton               matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        OutputArraysTextArea            matlab.ui.control.TextArea
        OutputArraysTextAreaLabel       matlab.ui.control.Label
        NaturalFrequenciesHzTextArea    matlab.ui.control.TextArea
        NaturalFrequenciesHzTextAreaLabel  matlab.ui.control.Label
        NaturalFreqModesMethodTab       matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        CalculationTab_2                matlab.ui.container.Tab
        NaturalFrequenciesListBox       matlab.ui.control.ListBox
        NaturalFrequenciesListBoxLabel  matlab.ui.control.Label
        MassNormalizedEigenvectorArrayListBoxLabel  matlab.ui.control.Label
        MassNormalizedEigenvectorArrayListBox  matlab.ui.control.ListBox
        UpdateListsButton_2             matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        ResultsTab_2                    matlab.ui.container.Tab
        OutputArraysTextArea_2          matlab.ui.control.TextArea
        OutputArraysTextArea_2Label     matlab.ui.control.Label
        MaxFreqHzEditField              matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel         matlab.ui.control.Label
        MinFreqHzEditField              matlab.ui.control.EditField
        MinFreqHzEditFieldLabel         matlab.ui.control.Label
        FrequencyStepHzEditField        matlab.ui.control.EditField
        FrequencyStepHzEditFieldLabel   matlab.ui.control.Label
        MaximumModeNumberofInterestEditField  matlab.ui.control.EditField
        MaximumModeNumberofInterestEditFieldLabel  matlab.ui.control.Label
        OutputFunctionsListBox          matlab.ui.control.ListBox
        OutputFunctionsListBoxLabel     matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        transfer_functions_from_modes_aver11byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        iu % Description
        n
        df
        fmin
        fmax
        ifrf

        fn
        ModeShapes
        MST
        dm

        iforce
        iresponse
        iam

        mass
        stiffness

        sso

    end
    
    methods (Access = private)

        function Post(app)    

            tpi=2*pi;

            nf=floor((app.fmax-app.fmin)/app.df);
            
            %%%%%%%
            
            %%% nrb=-1+get(handles.listbox_nrb,'Value');
            
            nrb=0;  % leave zero

            num_modes=app.n;

            if(num_modes>length(app.fn))
                num_modes=length(app.fn);
            end
            
            if(nrb>=num_modes)
               warndlg('number of rigid body modes >= num_modes'); 
               return; 
            end
            
            %%%%%%%
            
            freq=zeros(nf,1);
            omega=zeros(nf,1);
            omega2=zeros(nf,1);
            
            for i=1:nf
                freq(i)=(i-1)*app.df+app.fmin;
                omega(i)=2*pi*freq(i);
                omega2(i)=(omega(i))^2;
            end
            %
            clear omn;
            omn=tpi*app.fn;
            omn2=omn.*omn;
            %

            QE=app.ModeShapes;

            sz=size(QE);
            num_dof=sz(1);
            num_columns=sz(2);
            
            %
            %%%%%%%
  
            if isempty(app.iresponse)
               warndlg('Enter response dof'); 
               return; 
            end
            
            if(app.iresponse>num_dof)
                out1=sprintf('Error: response_dof=%d  num_dof=%d',app.iresponse,num_dof);
                warndlg(out1);
                return;
            end
            
            %%%%%%%
               
             
            if isempty(app.iforce)
               warndlg('Enter excitation dof'); 
               return; 
            end
             
            if(app.iforce>num_dof)
                out1=sprintf('Error: excitation_dof=%d  num_dof=%d',excitation_dof,num_dof);
                warndlg(out1);
                return;
            end
            
            %%%%%%%

            fnv=app.fn;
            dampv=app.dm;
                
            i=app.iresponse;
            k=app.iforce;

            max_num=app.n;
            
            [H_response_force]=...
            transfer_core_n(freq,fnv,dampv,QE,omn2,omega,omega2,app.iam,nf,num_columns,i,k,nrb,max_num);
            %
            if(app.iam ~=7)
                H=H_response_force;
            else
                [H_base_force]=...
                transfer_core_n(freq,fnv,dampv,QE,omn2,omega,omega2,app.iam,nf,num_columns,k,k,nrb,max_num);
            %
                H=zeros(nf,1);
                for s=1:nf % frequency loop  
                    H(s)=H_response_force(s)/H_base_force(s);
                end
            %
            end
            
            %%%%%%%
            
            H=-H;
            
            HM=abs(H);
            HP=angle(H)*180/pi;
            
            %
            if(app.iam==3 && app.iu==1)
                H=H/386;
                HM=HM/386;
            end
            %
            clear PPP;
            PPP=zeros(nf,1);
            PHA=zeros(nf,1);
            
            %    
            for ia=1:nf
                PPP(ia)=HM(ia);
                PHA(ia)=HP(ia);
            %
                if(PHA(ia)<-180)
                    PHA(ia)=PHA(ia)+360.;
                end
                if(PHA(ia)>180)
                    PHA(ia)=PHA(ia)-360.;
                end    
            %
            end  
            
            %
            freq=fix_size(freq);
            H=fix_size(H);
            HM=fix_size(HM);
            PPP=fix_size(PPP);       
            PHA=fix_size(PHA);
            AAA=angle(H);
            %
            [varname1,varname2,varname3]=transfer_from_modes_H_files_alt(app.iam,i,k,freq,H,HM);
            % 
            
            disp(' ');
            disp(' Matlab array names ');
            
                disp(' ');
                eval([varname1 ' = [freq H];']);    % need to do in main script
                out1=sprintf(' %s      Complex',varname1);
                disp(out1);
                assignin('base',varname1,[freq H]);
            %
                eval([varname2 ' = [freq PPP];']);
                out2=sprintf(' %s    Magnitude  %s ',varname2);
                disp(out2);
                assignin('base',varname2,[freq PPP]);    
            %
                eval([varname3 ' = [freq PPP AAA];']);
                out3=sprintf(' %s  Magnitude & Phase',varname3);
                disp(out3);   
                assignin('base',varname3,[freq PPP AAA]); 

                app.sso=sprintf('%s\n%s\n%s',out1,out2,out3);
            %

            fig_num=1;
            
            [fig_num]=...
            transfer_from_modes_plots_alt2(app.iu,app.iam,i,k,fig_num,app.fmin,app.fmax,H,freq,PPP,PHA);    
            %

        
        end

        function ChangeDamping(app)

            app.n=str2num(app.MaximumModeNumberofInterestEditField.Value);

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            if(contains(nD,'Uniform'))
                app.UITable.RowName={'Uniform'};
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData={10};
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData={0.05};                
                end   
            else

                for i=1:app.n
                    ssr{i}=sprintf('Mode %d',i);
                    ssq{i}='10';
                    ssd{i}='0.05';
                end    

                app.UITable.RowName=ssr';
                if(contains(nmn,'Amplification')) 
                    app.UITable.ColumnName={'Q'};
                    myData=ssq';
                else  
                    app.UITable.ColumnName={'Damping Ratio'};
                    myData=ssd';                
                end   
            end
            app.UITable.Data=myData;
            app.UITable.ColumnEditable = true;

        end


        function Pre(app)

            ClosePlots(app);

            Items = strtrim(app.OutputFunctionsListBox.Items);
            im = strtrim(app.OutputFunctionsListBox.Value);
            app.iam = find(strcmp(Items, im));

            app.iforce=str2num(app.ForceInputdofEditField.Value);
            app.iresponse=str2num(app.ResponsedofEditField.Value);

            app.n=str2num(app.MaximumModeNumberofInterestEditField.Value);
            app.df=str2num(app.FrequencyStepHzEditField.Value);
            app.fmin=str2num(app.MinFreqHzEditField.Value);
            app.fmax=str2num(app.MaxFreqHzEditField.Value);

            Items = strtrim(app.OutputFunctionsListBox.Items);
            im = strtrim(app.OutputFunctionsListBox.Value);
            app.ifrf = find(strcmp(Items, im));            
        end    

        function UpdateModes(app)
            [cell_array]=update_2D_array();

            try
                app.MassNormalizedEigenvectorArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.MassNormalizedEigenvectorArrayListBox.Items);
                app.MassNormalizedEigenvectorArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.MassNormalizedEigenvectorArrayListBox.Value=app.MassNormalizedEigenvectorArrayListBox.Items{1};

            %%%%

            [cell_array]=update_1D_array();

            try
                app.NaturalFrequenciesListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.NaturalFrequenciesListBox.Items);
                app.NaturalFrequenciesListBox.Value=strtrim(Items(1,:));
            catch
            end

            try
                app.NaturalFrequenciesListBox.Value=app.NaturalFrequenciesListBox.Items{1};
            catch
            end

        end
        
        function UpdateMK(app)
            [cell_array]=update_2D_array();

            try
                app.MassArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.MassInputArrayListBox.Items);
                app.MassInputArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.MassArrayListBox.Value=app.MassArrayListBox.Items{1};

            %%

            try
                app.StiffnessArrayListBox.Items=unique(sort(cell_array));          
            catch
            end

            try
                Items = char(app.StiffnessInputArrayListBox.Items);
                app.StiffnessArrayListBox.Value=strtrim(Items(1,:));
            catch
            end

            app.StiffnessArrayListBox.Value=app.StiffnessArrayListBox.Items{1};

        end

        function Units(app)
            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.MassunitLabel.Text='Mass unit is: lbf sec^2/in   Stiffness unit is: lbf/in';
            else    
                app.MassunitLabel.Text='Mass unit is: kg   Stiffness unit is: N/m';            
            end    

        end    

        function ClosePlots(app)
            
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            Units(app);
            UpdateMK(app);
            UpdateModes(app);
            ChangeDamping(app);
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable,s) % adjust the style
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            Units(app);
            
        end

        % Button pushed function: UpdateListsButton
        function UpdateListsButtonPushed(app, event)
            UpdateMK(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
         

            if(isempty(app.dm))
                app.n
                app.dm
                warndlg('Save Damping first');
                return;
            end 
            
            Pre(app);

           try
                FS=app.MassArrayListBox.Value;
                app.mass=evalin('base',FS);
            catch
                warndlg('Mass array read failed');
                return;
            end

            try
                FS=app.StiffnessArrayListBox.Value;
                app.stiffness=evalin('base',FS);
            catch
                warndlg('Stiffness array read failed');
                return;
            end


            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiffness,app.mass,2);

            for i=1:length(app.fn)
                if(i==1)
                    ss=sprintf(' %d.  %7.4g\n',i,app.fn(i));
                else
                    ss=sprintf('%s %d.  %7.4g\n',ss,i,app.fn(i));                   
                end    
            end    

            app.NaturalFrequenciesHzTextArea.Value=ss;

            Post(app);

            app.OutputArraysTextArea.Value=app.sso;

        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            app.n=str2num(app.MaximumModeNumberofInterestEditField.Value);

            nmn=app.DampingTypeListBox.Value;
            nD=app.ModalDistributionListBox.Value;

            DQ=app.UITable.Data;


            if(contains(nD,'Uniform'))

                if(contains(nmn,'Amplification')) 
                    v=1/(2*DQ{1});
                else  
                    v=DQ{1};
                end   
                app.dm=ones(app.n,1)*v;
                
            else
                ddd=str2num(char(DQ));

                if(contains(nmn,'Amplification')) 

                    for i=1:length(DQ)
                        v=1/(2*ddd(i));
                        app.dm(i)=v;
                    end
                else  
                              
                    for i=1:length(DQ)
                        v=ddd(i);
                        app.dm(i)=v;
                    end            
                end   
            end

            msgbox('Damping saved');
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)
         
            ChangeDamping(app);
        end

        % Value changed function: MaximumModeNumberofInterestEditField
        function MaximumModeNumberofInterestEditFieldValueChanged(app, event)
            app.n = str2num(app.MaximumModeNumberofInterestEditField.Value);
            ChangeDamping(app);
        end

        % Value changing function: MaximumModeNumberofInterestEditField
        function MaximumModeNumberofInterestEditFieldValueChanging(app, event)
            
            app.dm=[];
            app.n=str2num(app.MaximumModeNumberofInterestEditField.Value);
            ChangeDamping(app);
        end

        % Button pushed function: UpdateListsButton_2
        function UpdateListsButton_2Pushed(app, event)
            UpdateModes(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            
            if(isempty(app.dm))
                app.n
                app.dm
                warndlg('Save Damping first');
                return;
            end 
            
            Pre(app);

           try
                FS=app.MassArrayListBox.Value;
                app.ModeShapes=evalin('base',FS);
            catch
                warndlg('Eigenvector array read failed');
                return;
            end

            try
                FS=app.NaturalFrequenciesListBox.Value;
                app.fn=evalin('base',FS);
            catch
                warndlg('Natural Frequency array read failed');
                return;
            end

            Post(app);

            
            if(isempty(app.dm))
                app.n
                app.dm
                warndlg('Save Damping first');
                return;
            end 
            
            Pre(app);

           try
                FS=app.MassArrayListBox.Value;
                app.mass=evalin('base',FS);
            catch
                warndlg('Mass array read failed');
                return;
            end

            try
                FS=app.StiffnessArrayListBox.Value;
                app.stiffness=evalin('base',FS);
            catch
                warndlg('Stiffness array read failed');
                return;
            end


            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiffness,app.mass,2);

            for i=1:length(app.fn)
                if(i==1)
                    ss=sprintf(' %d.  %7.4g\n',i,app.fn(i));
                else
                    ss=sprintf('%s %d.  %7.4g\n',ss,i,app.fn(i));                   
                end    
            end    

            app.NaturalFrequenciesHzTextArea.Value=ss;

            Post(app);

            app.OutputArraysTextArea_2.Value=app.sso;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.transferfunctionsUIFigure = uifigure('Visible', 'off');
            app.transferfunctionsUIFigure.Name = 'transfer functions';
            app.ReturnButton = uibutton(app.transferfunctionsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.transfer_functions_from_modes_aver11byTomIrvineLabel = uilabel(app.transferfunctionsUIFigure);
            app.transfer_functions_from_modes_aver11byTomIrvineLabel.Text = 'transfer_functions_from_modes_a    ver 1.1   by Tom Irvine';
            app.UnitsListBoxLabel = uilabel(app.transferfunctionsUIFigure);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.transferfunctionsUIFigure);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.OutputFunctionsListBoxLabel = uilabel(app.transferfunctionsUIFigure);
            app.OutputFunctionsListBoxLabel.Text = 'Output Functions';
            app.OutputFunctionsListBox = uilistbox(app.transferfunctionsUIFigure);
            app.OutputFunctionsListBox.Items = {'displacement/force', 'velocity/force', 'acceleration/force', 'force/displacement', 'force/velocity', 'force/acceleration', 'acceleration/acceleration'};
            app.OutputFunctionsListBox.Value = 'displacement/force';
            app.MaximumModeNumberofInterestEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.MaximumModeNumberofInterestEditFieldLabel.Text = {'Maximum Mode '; 'Number of Interest'};
            app.MaximumModeNumberofInterestEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.MaximumModeNumberofInterestEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumModeNumberofInterestEditFieldValueChanged, true);
            app.MaximumModeNumberofInterestEditField.ValueChangingFcn = createCallbackFcn(app, @MaximumModeNumberofInterestEditFieldValueChanging, true);
            app.MaximumModeNumberofInterestEditField.Value = '1';
            app.FrequencyStepHzEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.FrequencyStepHzEditFieldLabel.Text = 'Frequency Step (Hz)';
            app.FrequencyStepHzEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.MinFreqHzEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.MaxFreqHzEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.TabGroup = uitabgroup(app.transferfunctionsUIFigure);
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.UITable = uitable(app.DampingTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.MassStiffnessMethodTab = uitab(app.TabGroup);
            app.MassStiffnessMethodTab.Title = 'Mass & Stiffness Method';
            app.TabGroup2 = uitabgroup(app.MassStiffnessMethodTab);
            app.CalculationTab = uitab(app.TabGroup2);
            app.CalculationTab.Title = 'Calculation';
            app.UpdateListsButton = uibutton(app.CalculationTab, 'push');
            app.UpdateListsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListsButtonPushed, true);
            app.UpdateListsButton.Text = 'Update Lists';
            app.CalculateButton = uibutton(app.CalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassunitLabel = uilabel(app.CalculationTab);
            app.MassunitLabel.Text = 'Mass unit';
            app.StiffnessArrayListBox = uilistbox(app.CalculationTab);
            app.StiffnessArrayListBox.Items = {''};
            app.StiffnessArrayListBox.Value = '';
            app.StiffnessArrayListBoxLabel = uilabel(app.CalculationTab);
            app.StiffnessArrayListBoxLabel.Text = 'Stiffness Array';
            app.MassArrayListBoxLabel = uilabel(app.CalculationTab);
            app.MassArrayListBoxLabel.Text = 'Mass Array';
            app.MassArrayListBox = uilistbox(app.CalculationTab);
            app.MassArrayListBox.Items = {''};
            app.MassArrayListBox.Value = '';
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.NaturalFrequenciesHzTextAreaLabel = uilabel(app.ResultsTab);
            app.NaturalFrequenciesHzTextAreaLabel.Text = 'Natural Frequencies (Hz)';
            app.NaturalFrequenciesHzTextArea = uitextarea(app.ResultsTab);
            app.OutputArraysTextAreaLabel = uilabel(app.ResultsTab);
            app.OutputArraysTextAreaLabel.Text = 'Output Arrays';
            app.OutputArraysTextArea = uitextarea(app.ResultsTab);
            app.NaturalFreqModesMethodTab = uitab(app.TabGroup);
            app.NaturalFreqModesMethodTab.Title = 'Natural Freq & Modes Method';
            app.TabGroup3 = uitabgroup(app.NaturalFreqModesMethodTab);
            app.CalculationTab_2 = uitab(app.TabGroup3);
            app.CalculationTab_2.Title = 'Calculation';
            app.CalculateButton_2 = uibutton(app.CalculationTab_2, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.UpdateListsButton_2 = uibutton(app.CalculationTab_2, 'push');
            app.UpdateListsButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListsButton_2Pushed, true);
            app.UpdateListsButton_2.Text = 'Update Lists';
            app.MassNormalizedEigenvectorArrayListBox = uilistbox(app.CalculationTab_2);
            app.MassNormalizedEigenvectorArrayListBox.Items = {''};
            app.MassNormalizedEigenvectorArrayListBox.Value = '';
            app.MassNormalizedEigenvectorArrayListBoxLabel = uilabel(app.CalculationTab_2);
            app.MassNormalizedEigenvectorArrayListBoxLabel.Text = 'Mass Normalized Eigenvector Array';
            app.NaturalFrequenciesListBoxLabel = uilabel(app.CalculationTab_2);
            app.NaturalFrequenciesListBoxLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesListBox = uilistbox(app.CalculationTab_2);
            app.NaturalFrequenciesListBox.Items = {''};
            app.NaturalFrequenciesListBox.Value = '';
            app.ResultsTab_2 = uitab(app.TabGroup3);
            app.ResultsTab_2.Title = 'Results';
            app.OutputArraysTextArea_2Label = uilabel(app.ResultsTab_2);
            app.OutputArraysTextArea_2Label.Text = 'Output Arrays';
            app.OutputArraysTextArea_2 = uitextarea(app.ResultsTab_2);
            app.ForceInputdofEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.ForceInputdofEditFieldLabel.Text = 'Force Input dof';
            app.ForceInputdofEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.ForceInputdofEditField.Value = '1';
            app.ResponsedofEditFieldLabel = uilabel(app.transferfunctionsUIFigure);
            app.ResponsedofEditFieldLabel.Text = 'Response dof';
            app.ResponsedofEditField = uieditfield(app.transferfunctionsUIFigure, 'text');
            app.ResponsedofEditField.Value = '1';
            % Show the figure after all components are created
            app.transferfunctionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transfer_functions_from_modes_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.transferfunctionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.transferfunctionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.transferfunctionsUIFigure)
        end
    end
end