classdef transmitted_pressure_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UITransmittedPressureUIFigure  matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        transmitted_pressure_aver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SDOFTab                        matlab.ui.container.Tab
        CalculateResponseButton        matlab.ui.control.Button
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyHzEditField    matlab.ui.control.NumericEditField
        ResultsTextAreaLabel           matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        VRSTab                         matlab.ui.container.Tab
        CalculateVRSButton             matlab.ui.control.Button
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        fmin                           matlab.ui.control.NumericEditField
        fmax                           matlab.ui.control.NumericEditField
        minLabel                       matlab.ui.control.Label
        maxLabel                       matlab.ui.control.Label
        OutputTypeListBoxLabel         matlab.ui.control.Label
        OutputTypeListBox              matlab.ui.control.ListBox
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        OutputArrayNameEditField       matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        InputArrayNameEditFieldLabel   matlab.ui.control.Label
        InputArrayNameEditField        matlab.ui.control.EditField
        PressureUnitListBoxLabel       matlab.ui.control.Label
        PressureUnitListBox            matlab.ui.control.ListBox
        AmplificationFactorEditFieldLabel  matlab.ui.control.Label
        AmplificationFactorEditField   matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel      matlab.ui.control.Label
        DurationsecEditField           matlab.ui.control.NumericEditField
        DurationaffectspeakresponseonlyLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        iu  %
        ref %
        trans_pressure_vrs_1_sigma; %
        trans_pressure_vrs_3_sigma; %
        trans_pressure_vrs_peak;    %   
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                         
        end
        
        function reference(app)
            ius=app.PressureUnitListBox.Value;
            if(strcmp(ius,'psi'))
                app.iu=1;
                app.ref=2.9e-09; 
            else
                app.iu=2;
                app.ref=20.e-06;     
            end         
            
        end
        
        function Draw(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off';
            app.SaveButton.Enable='off';    
            reference(app);
            % Draw(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateVRSButton
        function CalculateVRSButtonPushed(app, event)
            ClosePlots(app);
            reference(app);
            
            disp(' ');
            disp(' * * * * * *');
            disp(' ');
            
            fig_num=1;
            
            tpi=2*pi;
            
            FS = strtrim(app.InputArrayNameEditField.Value);
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end                 
            
            if(THM(:,1)<1.0e-20)
                THM(1,:)=[];
            end   
            
            n=length(THM(:,1));
            
            spec_minf=THM(1,1);
            spec_maxf=THM(n,1);
            
            dur=app.DurationsecEditField.Value;
            Q=app.AmplificationFactorEditField.Value;
            
            damp=1/(2*Q);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            db=THM(:,2);
            %
            [oadb]=oaspl_function(db);
            %
            
            nf=1;
            n_type=1;
            
            ttt=' ';
            
            fprintf('\n  Overall Level = %8.4g dB \n\n',oadb);
            disp(' zero dB Reference = 20 micro Pascal ');
            disp('  ');
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(nf==1)
                if(n_type==1)
                    t_string=sprintf(' One-Third Octave Sound Pressure Level, %s\n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                else
                    t_string=sprintf(' Full Octave Sound Pressure Level, %s \n OASPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);    
                end    
            else
                if(n_type==1)
                    t_string=sprintf(' One-Third Octave FPL, %s \n OAFPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);
                else
                    t_string=sprintf(' Full Octave FPL, %s \n OAFPL = %8.4g dB  Ref: 20 micro Pa ',ttt,oadb);    
                end      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fmin=THM(1,1);
            fmax=THM(end,1);
            
            %
                figure(fig_num);
                
                plot(app.UIAxes,THM(:,1),THM(:,2));
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
                
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);     
                end
                
                dB=THM(:,2);
            
                [ymin,ymax]=dB_ylimits(dB);
                
                ylim([ymin,ymax]);    
                
                title(t_string)
                xlabel('Center Frequency (Hz)');
                
                ylabel('SPL (dB)');
                    
                grid on;
                set(gca, 'xminorgrid', 'on', 'yminorgrid', 'on')
                set(gca,'MinorGridLineStyle',':','GridLineStyle','-','XScale','log','YScale','lin');
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            fc=THM(:,1);
            spl=THM(:,2);
            %
            rms=0.;
            %   
            num=length(fc);
            psd=zeros(num,1);
            %
            delta=(2.^(1./6.)) - 1./(2.^(1./6.));
            %
            for i=1:num    
            %	
                if( spl(i) >= 1.0e-50)
            %		
                    pressure_rms=app.ref*(10.^(spl(i)/20.) );
            %
            		df=fc(i)*delta;
            %
                    if( df > 0. )	
                        psd(i)=(pressure_rms^2.)/df;
            			rms=rms+psd(i)*df;
                    end
                end
            end
            %
            power_spectral_density=[fc psd];
            %
            
            f1=app.fmin.Value;
            f2=app.fmax.Value;
            
            ocn=1/24;
            %
            fi(1)=f1;
            %
            j=2;
            while(1)
                fi(j)=fi(j-1)*2^(ocn);
                if(fi(j)>f2)
                    fi(j)=f2;
                    break;
                end
                j=j+1;
            end
            fn=fi;
            %
            
            n_ref=length(fi);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            f=power_spectral_density(:,1);
            a=power_spectral_density(:,2);
            
            [ai]=interp_psd_oct(f,a,fi);
            
            fi=fix_size(fi);
            ai=fix_size(ai);
            
            %
            omega=zeros(n_ref,1);
            omega2=zeros(n_ref,1);
            omegan=zeros(n_ref,1);
            omegan2=zeros(n_ref,1);
            
            %
            for j=1:n_ref
            %    
                omega(j)=tpi*fi(j);
                omega2(j)=omega(j)^2;
            %
                omegan(j)=tpi*fn(j);
                omegan2(j)=omegan(j)^2;
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            PT_rms=zeros(n_ref,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            progressbar;
            
            for ijk=1:n_ref
            %    
                progressbar(ijk/n_ref);
            %
                PT_psd=zeros(n_ref,1);
            %
            %      Transmitted Pressure
            %
                for j=1:n_ref
            %
                    rho=fi(j)/fn(ijk);
                    num= 1.+(2*damp*rho)^2;
                    den= (1-rho^2)^2 + (2*damp*rho)^2;
                    t= num/den;
                    PT_psd(j)=( t*ai(j) );
            %
                end
                
                [~,PT_rms_ind]=cm0(PT_psd,fi,n_ref);
                PT_rms(ijk)=PT_rms_ind;
            
            end
            %    
            progressbar(1);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fn=fix_size(fn);
            PT_rms=fix_size(PT_rms);
            
            app.trans_pressure_vrs_1_sigma=[fn  PT_rms];
            app.trans_pressure_vrs_3_sigma=[fn  3*PT_rms];        
                             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clear length;
            
            for i=1:length(fn)
            %    
                if(fn(i)>=spec_minf && fn(i)<=spec_maxf)
            %       
                    ax=0.5;
                    [ms]=risk_overshoot(fn(i),dur,ax);
            %
                    app.trans_pressure_vrs_peak(j,:)=[fn(i)  ms*PT_rms(i)];
                    
                    j=j+1;
                end   
            %       
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp=[ fi ai];
            
            [~,rms] = calculate_PSD_slopes(fi,ai);
            
            applied_pressure_rms=rms;
            
            
            if(app.iu==1)
                y_label='Pressure (psi^2/Hz)';
                t_string=sprintf('Applied Pressure PSD  %6.2g psi RMS',applied_pressure_rms);
               
            else
                y_label='Pressure (Pa^2/Hz)'; 
                t_string=sprintf('Applied Pressure PSD  %8.4g Pa RMS',applied_pressure_rms);     
            end
            
            x_label='Frequency (Hz)';
            
            md=6;
            fig_num=2;
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,f1,f2,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp1=app.trans_pressure_vrs_peak;
            ppp2=[fn 3*PT_rms];
            ppp3=[fn   PT_rms];   
            
            leg1='peak';
            leg2='3-sigma';
            leg3='1-sigma';
            
            if(app.iu==1)
                y_label='Pressure (psi)';
            else 
                y_label='Pressure (Pa)';
            end
            
            x_label='Natural Frequency (Hz)';
            
            t_string=sprintf('Transmitted Pressure VRS Q=%g ',Q);
            
            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,f1,f2,md);         
            
            app.SaveButton.Enable='on';               
                           
        end

        % Button pushed function: CalculateResponseButton
        function CalculateResponseButtonPushed(app, event)
            ClosePlots(app);
            reference(app);
            
            disp(' ');
            disp(' * * * * * *');
            disp(' ');
            
            fig_num=1;
            
            tpi=2*pi;
            
            FS = strtrim(app.InputArrayNameEditField.Value);
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end                 
            
            if(THM(:,1)<1.0e-20)
                THM(1,:)=[];
            end
            
            n=length(THM(:,1));
            
            spec_minf=THM(1,1);
            spec_maxf=THM(n,1);
            
            dur=app.DurationsecEditField.Value;
            
            Q=app.AmplificationFactorEditField.Value;
            
            damp=1/(2*Q);
            
            fn=app.NaturalFrequencyHzEditField.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            n_type=1;
            
            f=THM(:,1);
            dB=THM(:,2);
            
            [fig_num]=spl_plot(app.UIAxes,fig_num,n_type,f,dB);
            
            disp('  ');
            %            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ffmin=THM(1,1);
            ffmax=THM(end,1);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            fc=THM(:,1);
            spl=THM(:,2);
            %
            rms=0.;
            %   
            num=length(fc);
            psd=zeros(num,1);
            %
            delta=(2.^(1./6.)) - 1./(2.^(1./6.));
            %
            for i=1:num    
            %	
                if( spl(i) >= 1.0e-50)
            %		
                    pressure_rms=app.ref*(10.^(spl(i)/20.) );
                    df=fc(i)*delta;
            %
            
                    if( df > 0. )	
                        psd(i)=(pressure_rms^2.)/df;
            			rms=rms+psd(i)*df;
                    end
                end
            end
            %
            power_spectral_density=[fc psd];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            f1=ffmin;
            f2=ffmax;
            
            ocn=1/24;
            %
            fi(1)=f1;
            %
            j=2;
            while(1)
                fi(j)=fi(j-1)*2^(ocn);
                if(fi(j)>f2)
                    fi(j)=f2;
                    break;
                end
                j=j+1;
            end
            
            n_ref=length(fi);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            f=power_spectral_density(:,1);
            a=power_spectral_density(:,2);
            
            [ai]=interp_psd_oct(f,a,fi);
            
            fi=fix_size(fi);
            ai=fix_size(ai);
            
            %
            omega=zeros(n_ref,1);
            omega2=zeros(n_ref,1);
            
            %
            for j=1:n_ref
            %    
                omega(j)=tpi*fi(j);
                omega2(j)=omega(j)^2;
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            PT_psd=zeros(n_ref,1);
            %
            %      Transmitted Pressure
            %
            for j=1:n_ref
            %
                rho=fi(j)/fn;
                num= 1.+(2*damp*rho)^2;
                den= (1-rho^2)^2 + (2*damp*rho)^2;
                t= num/den;
                PT_psd(j)=( t*ai(j) );
            %
            end
            
            [~,PT_rms_ind]=cm0(PT_psd,fi,n_ref);
            PT_rms=PT_rms_ind;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clear length;
            
            iflag=0;
            %    
            if(fn>=spec_minf && fn<=spec_maxf)
            %
                iflag=1;
               
                ax=0.5;
                [ms]=risk_overshoot(fn,dur,ax);
            %
            end
            %       
            ms=ms*PT_rms;
            
            if(app.iu==1)
                su='Transmitted Pressure (psi)';
            else 
                su='Transmitted Pressure (Pa)';
            end
            
            if(iflag==0)  
                ss=sprintf(' %s \n\n RMS = %8.4g \n\n  3-sigma = %8.4g ',su,PT_rms,3*PT_rms);
            else
                ss=sprintf(' %s \n\n RMS = %8.4g \n\n 3-sigma = %8.4g \n\n peak = %8.4g ',su,PT_rms,3*PT_rms,ms);
            end
            
            if(app.iu==1)
                vu='psi rms';
                y_label='Pressure (psi^2/Hz)';    
            else
                vu='Pa rms';
                y_label='Pressure (Pa^2/Hz)';      
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [~,rms] = calculate_PSD_slopes(fi,ai);
            
            applied_pressure_rms=rms;
            
            md=6;
            leg1=sprintf('Input %7.3g %s',applied_pressure_rms,vu);
            leg2=sprintf('Response %7.3g %s',PT_rms,vu);
            ppp1=[ fi ai];
            ppp2=[ fi PT_psd];
            x_label='Frequency (Hz)';
            t_string='Pressure Power Spectral Density';
            
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,ffmin,ffmax,md);


            app.ResultsTextArea.Enable='on';
            app.ResultsTextArea.Value=ss;
            app.ResultsTextAreaLabel.Enable='on'; 
        end

        % Value changed function: PressureUnitListBox
        function PressureUnitListBoxValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off'; 
            app.SaveButton.Enable='off';  
            reference(app);
        end

        % Value changed function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off'; 
            app.SaveButton.Enable='off';       
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off';  
            app.SaveButton.Enable='off';  
        end

        % Value changed function: AmplificationFactorEditField
        function AmplificationFactorEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off'; 
            app.SaveButton.Enable='off';  
        end

        % Value changed function: InputArrayNameEditField
        function InputArrayNameEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.ResultsTextAreaLabel.Enable='off';
            app.SaveButton.Enable='off';     
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=strtrim(app.OutputArrayNameEditField.Value);
            L=strtrim(app.OutputTypeListBox.Value);
            
            if(strcmp(L,'1-sigma'))
                data=app.trans_pressure_vrs_1_sigma;
            end
            if(strcmp(L,'3-sigma'))
                data=app.trans_pressure_vrs_3_sigma;
            end
            if(strcmp(L,'Peak'))
                data=app.trans_pressure_vrs_peak;
            end         
            
            try
                assignin('base',name, data);                
            catch
                warndlg('save failed');
            end                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UITransmittedPressureUIFigure = uifigure('Visible', 'off');
            app.UITransmittedPressureUIFigure.Name = 'UI Transmitted Pressure';
            app.ReturnButton = uibutton(app.UITransmittedPressureUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.transmitted_pressure_aver10byTomIrvineLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.transmitted_pressure_aver10byTomIrvineLabel.Text = 'transmitted_pressure_a   ver 1.0  by Tom Irvine     SDOF Response to SPL Excitation';
            app.TabGroup = uitabgroup(app.UITransmittedPressureUIFigure);
            app.SDOFTab = uitab(app.TabGroup);
            app.SDOFTab.Title = 'SDOF';
            app.CalculateResponseButton = uibutton(app.SDOFTab, 'push');
            app.CalculateResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateResponseButtonPushed, true);
            app.CalculateResponseButton.Text = 'Calculate Response';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.SDOFTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.SDOFTab, 'numeric');
            app.NaturalFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanged, true);
            app.ResultsTextAreaLabel = uilabel(app.SDOFTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.SDOFTab);
            app.VRSTab = uitab(app.TabGroup);
            app.VRSTab.Title = 'VRS';
            app.CalculateVRSButton = uibutton(app.VRSTab, 'push');
            app.CalculateVRSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVRSButtonPushed, true);
            app.CalculateVRSButton.Text = 'Calculate VRS';
            app.PlotFrequenciesHzLabel = uilabel(app.VRSTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.fmin = uieditfield(app.VRSTab, 'numeric');
            app.fmin.Value = 20;
            app.fmax = uieditfield(app.VRSTab, 'numeric');
            app.fmax.Value = 2000;
            app.minLabel = uilabel(app.VRSTab);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.VRSTab);
            app.maxLabel.Text = 'max';
            app.OutputTypeListBoxLabel = uilabel(app.VRSTab);
            app.OutputTypeListBoxLabel.Text = 'Output Type';
            app.OutputTypeListBox = uilistbox(app.VRSTab);
            app.OutputTypeListBox.Items = {'1-sigma', '3-sigma', 'Peak'};
            app.OutputTypeListBox.Value = '1-sigma';
            app.OutputArrayNameEditFieldLabel = uilabel(app.VRSTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.VRSTab, 'text');
            app.SaveButton = uibutton(app.VRSTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.UITransmittedPressureUIFigure);
            app.Label.Text = 'The input file must have two columns:  freq (Hz) & SPL (dB)   in One-Third Octave Band format';
            app.InputArrayNameEditFieldLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UITransmittedPressureUIFigure, 'text');
            app.InputArrayNameEditField.ValueChangedFcn = createCallbackFcn(app, @InputArrayNameEditFieldValueChanged, true);
            app.PressureUnitListBoxLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.PressureUnitListBoxLabel.Text = 'Pressure Unit';
            app.PressureUnitListBox = uilistbox(app.UITransmittedPressureUIFigure);
            app.PressureUnitListBox.Items = {'psi', 'Pa'};
            app.PressureUnitListBox.ValueChangedFcn = createCallbackFcn(app, @PressureUnitListBoxValueChanged, true);
            app.PressureUnitListBox.Value = 'psi';
            app.AmplificationFactorEditFieldLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.AmplificationFactorEditFieldLabel.Text = 'Amplification Factor';
            app.AmplificationFactorEditField = uieditfield(app.UITransmittedPressureUIFigure, 'numeric');
            app.AmplificationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @AmplificationFactorEditFieldValueChanged, true);
            app.AmplificationFactorEditField.Value = 10;
            app.DurationsecEditFieldLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UITransmittedPressureUIFigure, 'numeric');
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.DurationsecEditField.Value = 60;
            app.DurationaffectspeakresponseonlyLabel = uilabel(app.UITransmittedPressureUIFigure);
            app.DurationaffectspeakresponseonlyLabel.Text = '(Duration affects peak response only)';
            app.Image = uiimage(app.UITransmittedPressureUIFigure);
            app.Image.ImageSource = 'sdof_force.jpg';
            % Show the figure after all components are created
            app.UITransmittedPressureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmitted_pressure_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UITransmittedPressureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UITransmittedPressureUIFigure)
        end
    end
end