classdef two_dof_base_aa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UITwodofBaseaaUIFigure          matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        two_dof_base_aaver10byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        NaturalFreqTab                  matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        Stiffness1EditField             matlab.ui.control.NumericEditField
        Stiffness1EditFieldLabel        matlab.ui.control.Label
        Stiffness2EditField             matlab.ui.control.NumericEditField
        Stiffness2EditFieldLabel        matlab.ui.control.Label
        Mass1EditField                  matlab.ui.control.NumericEditField
        Mass1EditFieldLabel             matlab.ui.control.Label
        Mass2EditField                  matlab.ui.control.NumericEditField
        Mass2EditFieldLabel             matlab.ui.control.Label
        DampingTab                      matlab.ui.container.Tab
        SaveDampingButton               matlab.ui.control.Button
        Mode2EditField                  matlab.ui.control.NumericEditField
        Mode2EditFieldLabel             matlab.ui.control.Label
        DampingUnitLabel                matlab.ui.control.Label
        Mode1EditField                  matlab.ui.control.NumericEditField
        Mode1EditFieldLabel             matlab.ui.control.Label
        ListBoxUniform                  matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        ListBoxQ                        matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        SelectTypesLabel                matlab.ui.control.Label
        TransTab                        matlab.ui.container.Tab
        OutputArrayNameEditFieldTrans   matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        AmplitudeTypeLabel              matlab.ui.control.Label
        ExportAmplitude                 matlab.ui.control.ListBox
        Label_4                         matlab.ui.control.Label
        ExportArrayLabel                matlab.ui.control.Label
        ExportTrans                     matlab.ui.control.ListBox
        Label_3                         matlab.ui.control.Label
        SaveButton_Trans                matlab.ui.control.Button
        EndFreqHzEditField              matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.NumericEditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateButton_Trans           matlab.ui.control.Button
        SteadySineTab                   matlab.ui.container.Tab
        SettoFirstModalFreqButton       matlab.ui.control.Button
        CalculateButton_Sine            matlab.ui.control.Button
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        SteadyStateSineBaseInputLabel   matlab.ui.control.Label
        AccelGEditField                 matlab.ui.control.NumericEditField
        AccelGEditFieldLabel            matlab.ui.control.Label
        HalfSineTab                     matlab.ui.container.Tab
        DurationsecEditField            matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        ResponsetoHalfSineBaseInputLabel  matlab.ui.control.Label
        BaseInputAccelGEditField        matlab.ui.control.NumericEditField
        BaseInputAccelGEditFieldLabel   matlab.ui.control.Label
        CalculateButton_HalfSine        matlab.ui.control.Button
        ArbitraryTab                    matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SelectOutputListBox             matlab.ui.control.ListBox
        SelectOutputListBoxLabel        matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        ResponsetoarbitrarybaseinputLabel  matlab.ui.control.Label
        TheinputarraymusthavetwocolumnsTimesecAccelGLabel  matlab.ui.control.Label
        CalculateButton_Arbitrary       matlab.ui.control.Button
        PSDTab                          matlab.ui.container.Tab
        OutputArrayListBox              matlab.ui.control.ListBox
        OutputArrayListBoxLabel         matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        SaveButton_PSD                  matlab.ui.control.Button
        InputArrayNameEditField_PSD     matlab.ui.control.EditField
        InputArrayNameEditField_3Label  matlab.ui.control.Label
        InputArrayNamePSD               matlab.ui.control.Label
        CalculateButton_PSD             matlab.ui.control.Button
        SRSTab                          matlab.ui.container.Tab
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField_SRS     matlab.ui.control.EditField
        InputArrayNameEditField_2Label  matlab.ui.control.Label
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        CalculateButton_SRS             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        iu    % unit
        mass  % mass array
        stiffness % stiffness array
        fn    % natural frequency vector
        omegan % angular natural frequency vector
        ModeShapes % mass-normalized mode shapes
        MST   % mode shapes transpose
        pff   % participation factors
        damp1 % viscous damping ratio 1
        damp2 % viscous damping ratio 2  
        dm    % damping vector
        mm    % mass vector
        a1s   % acceleration mass 1
        a2s   % acceleration mass 2
        rd1s  % relative displacement mass 1
        rd2s  % relative displacement mass 2
        rd21s % relative displacmeent mass2 - mass 1   
        acc_trans        % accel transmissibility
        acc_power_trans  % accel power transmissibility
        rd_power_trans   % rel disp transmissibility
        rd_trans         % rel disp power transmissibility
        acc_response_psd  % accel response PSD
        rd_response_psd   % rel disp response PSD
        rd21_response_psd % rel disp response PSD  mass2 - mass 1 
        a1_response_psd   % accel response mass 1
        a2_response_psd   % accel response mass 2
        rd1_response_psd  % rel disp response PSD mass 1
        rd2_response_psd  % rel disp response PSD mass 2           
    end
    
    methods (Access = private)
        
        function ChangeDamping(app)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                app.DampingUnitLabel.Text = 'Q';
            else
                app.DampingUnitLabel.Text = 'Ratio';
            end
            
            if(contains(m,'Uniform'))
                app.Mode1EditFieldLabel.Text='Uniform';
                app.Mode2EditField.Visible = 'off';
                app.Mode2EditFieldLabel.Visible = 'off';                
            else
                app.Mode1EditFieldLabel.Text='Mode 1';                
                app.Mode2EditField.Visible = 'on';
                app.Mode2EditFieldLabel.Visible = 'on';                 
            end            

        end
        
        function check(app)
            try
                if(min(app.fn)<=0)
                    warndlg('Zero frequency detected. Run Natural Frequency Tab')
                    return;
                end
            catch
                warndlg('Run Natural Freuqency Tab')
                return;
            end
            
            try
                if(app.damp1>=0 && app.damp2>=0)
                else    
                    warndlg('Enter Damping at Damping Tab');
                    return;                
                end
            catch
                warndlg('Enter Damping at Damping Tab');
                return;
            end
            
        end
        
        function close_figures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            app.SaveButton.Enable='off';     
            app.SaveButton_Trans.Enable='off';
            app.SaveButton_PSD.Enable='off';
            
            ChangeDamping(app);
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [cmap]=cmap_colors();
            
            %%%%%% base %%%%%%%%%%%%

            x=[-5.5 -5.5 5.5 5.5 -5.5];
            y=[0 0.5 0.5 0 0]-4.5;
 
            plot(app.UIAxes,x,y,'Color',cmap(1,:),'linewidth',0.75);
            hold(app.UIAxes,'on');

            x=[-4 -4 4 4 -4];
            y=[3 6 6 3 3]-3;
 
            plot(app.UIAxes,x,y,'Color',cmap(1,:),'linewidth',0.75);

            x=[-4 -4 4 4 -4];
            y=[3 6 6 3 3]+4;
 
            plot(app.UIAxes,x,y,'Color',cmap(1,:),'linewidth',0.75);

            xlim(app.UIAxes,[-12 12]);
            ylim(app.UIAxes,[-6 12]);

            %%%%%% text %%%%%%%%%%%%
 
            text(app.UIAxes,7,3,'x_{1}','fontsize',13.5);
            text(app.UIAxes,7,10,'x_{2}','fontsize',13.5);
 
            text(app.UIAxes,-0.9,8.5,'m_{2}','fontsize',13.5);
            text(app.UIAxes,-0.9,1.5,'m_{1}','fontsize',13.5);
            text(app.UIAxes,-4,5,'k_{2}','fontsize',13.5);
            text(app.UIAxes,-4,-2,'k_{1}','fontsize',13.5);

            text(app.UIAxes,8,-2,'y','fontsize',13.5);
            text(app.UIAxes,7.825,-1.4,'..','fontsize',16);

            %%%%%% side lines %%%%%%%%%%%%
 
            x=[4 6];
            y=[1.5 1.5];
            plot(app.UIAxes,x,y,'Color','k');
 
            x=[4 6];
            y=[8.5 8.5];
            plot(app.UIAxes,x,y,'Color','k');

            x=[4.5 6]+1;
            y=[-4.25 -4.25];
            plot(app.UIAxes,x,y,'Color','k');  

            %%%%%% arrows %%%%%%%%%%%%
 
            x=[6 6];
            y=[7 8.75]+1.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6-0.3 6+0.3 6 6-0.3];
            y=[8.5 8.5 8.5+0.3 8.5]+1.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6 6];
            y=[7 8.75]-5.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6-0.3 6+0.3 6 6-0.3];
            y=[8.5 8.5 8.5+0.3 8.5]-5.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6 6]+1;
            y=[7.25 9.75]-11.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6-0.3 6+0.3 6 6-0.3]+1;
            y=[8.5 8.5 8.5+0.3 8.5]-10.5;
            plot(app.UIAxes,x,y,'Color','k');

            %%%%%% spring %%%%%%%%%%%%        
 
            nn=2000;
 
            dt=4/(nn-1);
 
            t=zeros(nn,1);
            for i=1:nn
                t(i)=(i-1)*dt;
            end
            t=t-0.75;
            y=sawtooth(2*pi*t,0.5);
 
            t=3*t/(max(t)-min(t))+2;
 
            plot(app.UIAxes,y,t+2,'Color',cmap(5,:),'linewidth',0.75);
            plot(app.UIAxes,y,t+2-7,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[3 min(t+2)];
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[max(t)+2 max(t)+2.5];
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[3 min(t+2)]-7;
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[max(t)+2 max(t)+2.5]-7;
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            hold(app.UIAxes,'off');

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.iu=2;
            
            if(contains(app.UnitsListBox.Value,'English'))
                app.iu=1;
            end    
            
            m1=app.Mass1EditField.Value;
            k1=app.Stiffness1EditField.Value;
            m2=app.Mass2EditField.Value;
            k2=app.Stiffness2EditField.Value;
            
            app.mass=zeros(2,2);

            app.mass(1,1)=m1;
            app.mass(2,2)=m2;

            app.stiffness=[(k1+k2) -k2; -k2 k2];

            if(app.iu==1)
                app.mass=app.mass/386.;
            end

            app.mm=[app.mass(1,1); app.mass(2,2)];

            disp(' ');

            [app.fn,app.ModeShapes,app.pff,~]=tdof_fn_etr_results(app.mass,app.stiffness,app.iu);
            
            app.omegan=2*pi*app.fn;
            
            app.MST=app.ModeShapes';       
            
            msgbox('Results written to command window');
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq
        end

        % Value changed function: ListBoxQ
        function ListBoxQValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ListBoxUniform
        function ListBoxUniformValueChanged(app, event)
            ChangeDamping(app);    
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                if(contains(m,'Uniform'))
                     Q1=app.Mode1EditField.Value;
                     Q2=Q1;
                     app.damp1=1/(2*Q1);
                     app.damp2=app.damp1;
                else
                     Q1=app.Mode1EditField.Value;
                     Q2=app.Mode2EditField.Value;
                     app.damp1=1/(2*Q1);
                     app.damp2=1/(2*Q2);                
                end    
            else
                if(contains(m,'Uniform'))
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.damp1;
                     Q1=1/(2*app.damp1);
                     Q2=Q1;              
                else
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.Mode2EditField.Value;
                     Q1=1/(2*app.damp1);
                     Q2=1/(2*app.damp2);                   
                end                   
            end         
            
            app.dm=[app.damp1 app.damp2];
            
            fprintf('\n Damping Ratios \n Mode 1       2        \n');
            fprintf('    %6.3g  %6.3g  \n',app.damp1,app.damp2);


        end

        % Button pushed function: CalculateButton_Sine
        function CalculateButton_SinePushed(app, event)
        
            check(app);
        
            freq=app.FreqHzEditField.Value; 
            amp=app.AccelGEditField.Value;
            
            if(amp<1.0e-06)
                warndlg('Enter accel(G) >0 ')
                return;    
            end
            
                
            fprintf('\n Base Input \n');
            fprintf('   %8.4g G  %8.4g Hz\n\n',amp,freq);


            sz=size(app.ModeShapes);
            dof=sz(1);

            omega=2*pi*freq;
            om2=omega.^2;

            omn2=app.omegan.^2;

            two_damp_omegan=zeros(2,1);

            for i=1:dof 
                 two_damp_omegan(i)=2*app.dm(i)*app.omegan(i);
            end

            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %
            np=1;   
    
            Y=-app.MST*app.mm;

            rd=zeros(np,dof);
            acc=zeros(np,dof);

            for i=1:np 

                n=zeros(dof,1);

                for j=1:dof
                    A=Y(j);
                    den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                    n(j)=A/den;
                end
                na=-om2(i)*n;

                rd(i,:)=app.ModeShapes*n;
                acc(i,:)=app.ModeShapes*na;
                acc(i,:)=acc(i,:)+1;  

            end 
    
            if(app.iu==1)
                srd_trans=386*rd;
            else
                srd_trans=9.81*rd;   
            end
    
            sacc_trans=acc;

            a1=amp*sacc_trans(1,1);
            a2=amp*sacc_trans(1,2);

            rd1=amp*srd_trans(1,1);
            rd2=amp*srd_trans(1,2);

            aa=[ a1 a2 ];
            aa=abs(aa);
            amax=max(abs(aa));

            for i=1:2
                if(abs(aa(i))< (amax/10000) || abs(aa(i))< 1.0e-10)
                    aa(i)=0;
                end
            end

            if(app.iu==2)
                aa=aa/9.81;
            end    
        

            fprintf('\n Acceleration Response \n');
            fprintf('  Mass 1:  %8.4g G \n',aa(1));
            fprintf('  Mass 2:  %8.4g G \n',aa(2));

            rr=[ rd1 rd2 ];

            if(app.iu==1)
                dd='in'; 
            else
                dd='mm'; 
                rr=rr*1000;
            end

            rd21=rr(:,2)-rr(:,1);
            rr=abs(rr);
            rmax=max(abs(rr));

            for i=1:2
                if(abs(rr(i))< (rmax/10000) || abs(rr(i))< 1.0e-10)
                    rr(i)=0;
                end
            end
        
            fprintf('\n Relative Displacement Response \n');
            fprintf('  Mass 1:  %8.4g %s \n',rr(1),dd);
            fprintf('  Mass 2:  %8.4g %s \n',rr(2),dd);
            fprintf('  Mass 2 - Mass 1:  %8.4g %s \n',abs(rd21),dd);

            msgbox('Calculation complete.  Output written to Matlab Command Window.');

        end

        % Button pushed function: SettoFirstModalFreqButton
        function SettoFirstModalFreqButtonPushed(app, event)
            try
                ff=str2double(sprintf('%8.4g',app.fn(1)));
                app.FreqHzEditField.Value=ff;           
            catch
                warndlg('Run Natural Frequency Tab')
                return;
            end
        end

        % Button pushed function: CalculateButton_Arbitrary
        function CalculateButton_ArbitraryPushed(app, event)
            check(app);
            
            close_figures(app);

            fig_num=1;
            
            FS = app.InputArrayNameEditField.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end

            t=THM(:,1);
            base=THM(:,2);
        
            dt=mean(diff(t));

            dtmin=min(diff(t));
            dtmax=max(diff(t));

            fprintf('\n Time Step \n')
            fprintf(' dtmin  = %8.4g sec  \n',dtmin);
            fprintf(' dt     = %8.4g sec  \n',dt);
            fprintf(' dtmax  = %8.4g sec  \n',dtmax);
  
            fprintf('\n Sample Rate \n');
            fprintf(' srmin  = %8.4g samples/sec  \n',1/dtmax);
            fprintf(' sr     = %8.4g samples/sec  \n',1/dt);
            fprintf(' srmax  = %8.4g samples/sec  \n',1/dtmin);
    
            
            if(((dtmax-dtmin)/dt)>0.01)
                warndlg('Time step is not constant.');
                return;
            end

            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,base);
            data1=[t base];
            grid on;
            xlabel('Time(sec)');
            ylabel('Accel(G)');
            title('Base Input ');

            Q=app.ModeShapes;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Digital Recursive Filtering Relationship 
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            clear temp;
            temp=base;
            clear base;
            ntt=length(temp)+round((1.5/app.fn(1))/dt);
            base=zeros(ntt,1);
            base(1:length(temp))=temp;

            clear t;
            t=THM(1,1)+linspace(0,(ntt-1)*dt,ntt);

            nt=ntt;

            rd=zeros(nt,2);
            ra=zeros(nt,2);

            x=zeros(nt,2);
            a=zeros(nt,2);
    
            acc=zeros(nt,2);

            yy=-app.MST*app.mm*base';
    
            for j=1:2

                omegad=app.omegan(j)*sqrt(1.-(app.dm(j)^2));
  
                cosd=cos(omegad*dt);
                sind=sin(omegad*dt);

                domegadt=app.dm(j)*app.omegan(j)*dt;

                eee1=exp(-domegadt);
                eee2=exp(-2.*domegadt);

                ecosd=eee1*cosd;
                esind=eee1*sind; 

                a1= 2.*ecosd;
                a2=-eee2;    

                omeganT=app.omegan(j)*dt;

                phi=2*(app.dm(j))^2-1;
                DD1=(app.omegan(j)/omegad)*phi;
                DD2=2*DD1;
    
                df1=2*app.dm(j)*(ecosd-1) +DD1*esind +omeganT;
                df2=-2*omeganT*ecosd +2*app.dm(j)*(1-eee2) -DD2*esind;
                df3=(2*app.dm(j)+omeganT)*eee2 +(DD1*esind-2*app.dm(j)*ecosd);

                MD=app.omegan(j)^3*dt;
                df1=df1/MD;
                df2=df2/MD;
                df3=df3/MD;

                af1=esind/(omegad*dt);
                af2=-2*af1;
                af3=af1;

                yj=yy(j,:);
  
                %   Relative Acceleration Response
 
               forward=[ af1,  af2,  af3 ];    
               back   =[     1, -a1, -a2 ];    
  
               a(:,j)=filter(forward,back,yj);

               %   Relative Displacement Response   
               
               rd_forward=[ df1,  df2,  df3 ];    
               rd_back   =[     1, -a1, -a2 ];     
  
               x(:,j)=filter(rd_forward,rd_back,yj);

            end

            for i=1:nt 
                rd(i,:)=Q*x(i,:)';
                ra(i,:)=Q*a(i,:)';  
                acc(i,:)=ra(i,:);
            end

            for i=1:2
                acc(:,i)=acc(:,i)+base;
            end

            clear a;
            a=acc;

            if(app.iu==1)
                rd=rd*386;
            else
                rd=rd*9.81*1000;     
            end

            h1=figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,rd(:,1),t,rd(:,2));
            grid on; 
            xlabel('Time(sec)');

            out1=sprintf('Relative Displacement Response to Base Input');
       
            title(out1);
            if(app.iu==1)
                ylabel('Rel Disp(in)');
            else
                ylabel('Rel Disp(mm)');    
            end  
            legend('Mass 1','Mass 2');     
        
            h2=figure(fig_num);
            fig_num=fig_num+1;
            rd21=rd(:,2)-rd(:,1);
            plot(t,rd21);
            grid on; 
            xlabel('Time(sec)');

            out1=sprintf('Relative Displacement Mass 2 - Mass 1');
       
            title(out1);
            if(app.iu==1)
                ylabel('Rel Disp(in)');
            else
                ylabel('Rel Disp(mm)');    
            end

            h3=figure(fig_num);
            fig_num=fig_num+1;    
            plot(t,a(:,1),t,a(:,2));
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response to Base Input');   
            title(out1);

            ylabel('Accel(G)');
            legend('Mass 1','Mass 2');
        
            t=fix_size(t);
        
            aone=a(:,1);
            aone=fix_size(aone);
            atwo=a(:,2);
            atwo=fix_size(atwo);        

            h4=figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,aone);
            data2=[t aone];
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response Mass 1');   
            title(out1);

            ylabel('Accel(G)');

            h5=figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,atwo);
            data3=[t atwo];
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response Mass 2');   
            title(out1);

            ylabel('Accel(G)');
  
            a1=a(:,1);
            a2=a(:,2);

            rd1=rd(:,1);
            rd2=rd(:,2);
            
            app.a1s=[t a1];
            app.a2s=[t a2];
            app.rd1s=[t rd1];
            app.rd2s=[t rd2];
            app.rd21s=[t rd21];

            if(max(abs(a1))<1.0e-09 )
              a1=0.;
            end
            if(max(abs(a2))<1.0e-09 )
               a2=0.;
            end

            if(max(abs(rd1))<1.0e-09 )
                rd1=0.;
            end
            if(max(abs(rd2))<1.0e-09 )
                rd2=0.;
            end      
        
            fprintf('\n  Acceleration Response (G) \n');
            fprintf('              max       min  \n');
            fprintf('  Mass 1:  %7.4g   %7.4g \n',max(a1),min(a1));
            fprintf('  Mass 2:  %7.4g   %7.4g \n\n',max(a2),min(a2));
        
            if(app.iu==1)
                fprintf('  Relative Displacement Response (inch) \n');
            else
                fprintf('  Relative Displacement Response (mm) \n');            
            end
            fprintf('              max      min  \n');
            fprintf('  Mass 1:  %7.4g   %7.4g \n',max(rd1),min(rd1));
            fprintf('  Mass 2:  %7.4g   %7.4g \n\n',max(rd2),min(rd2));

            fprintf(' Mass 2-Mass 1:  %7.4g   %7.4g \n',max(rd21),min(rd21));
        
            xlabel3='Time (sec)';
            ylabel1='Accel (G)';
            ylabel2='Accel (G)';
            ylabel3='Accel (G)';
        
            t_string1='Base Input';
            t_string2='Mass 1 Response';
            t_string3='Mass 2 Response';
       
        
            [~]=...
                subplots_three_linlin_three_titles(fig_num,xlabel3,ylabel1,ylabel2,ylabel3,...
                                          data1,data2,data3,t_string1,t_string2,t_string3);

            app.SaveButton.Enable='on';                          
                                          
            msgbox('Calculation complete.  Output written to Matlab Command Window.');            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                V=strtrim(app.SelectOutputListBox.Value);    
                if(strcmp(V,'Acceleration Mass 1'))
                    data=app.a1s;
                end
                if(strcmp(V,'Acceleration Mass 2'))
                    data=app.a2s;            
                end
                if(strcmp(V,'Relative Disp Mass 1'))
                    data=app.rd1s;                
                end
                if(strcmp(V,'Relative Disp Mass 2'))
                    data=app.rd2s;                
                end
                if(strcmp(V,'Relative Disp Mass 2 - Mass 1'))
                    data=app.rd21s;
                end
            
                output_name=app.OutputArrayNameEditField.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end

        end

        % Button pushed function: CalculateButton_Trans
        function CalculateButton_TransPushed(app, event)
            check(app);
            
            fig_num=1;
             
            fmin=app.StartFreqHzEditField.Value;
            fmax=app.EndFreqHzEditField.Value;
             
            if(fmin<=0.01)
                fmin=0.01;
            end    

            sz=size(app.ModeShapes);
            dof=sz(1);

            N=96;

            [freq,omega,np]=frequencies_octave_spaced(fmin,fmax,N);

            om2=omega.^2;

            omn2=app.omegan.^2;

            two_damp_omegan=zeros(2,1);

            for i=1:dof 
                two_damp_omegan(i)=2*app.dm(i)*app.omegan(i);
            end

            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %           
            %
            rd=zeros(np,dof);
            rdm2m1=zeros(np,1);
            acc=zeros(np,dof);
            
            Y=-app.MST*app.mm;

            for i=1:np 

                n=zeros(2,1);

                for j=1:dof
                    A=Y(j);
                    den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                    n(j)=A/den;
                end
                na=-om2(i)*n;

                rd(i,:)=app.ModeShapes*n;
                acc(i,:)=app.ModeShapes*na;
                acc(i,:)=acc(i,:)+1;  

                rdm2m1(i,1)=rd(i,2)-rd(i,1);

            end 

            rd=abs(rd);
            rdm2m1=abs(rdm2m1);
            acc=abs(acc);

            if(app.iu==1)
                app.rd_trans=[freq 386*rd];
                rdm2m1_trans=[freq 386*rdm2m1];
            else
                app.rd_trans=[freq 9.81*rd];   
                rdm2m1_trans=[freq 9.81*rdm2m1];      
            end

            ppp1=[freq acc(:,1)];
            ppp2=[freq acc(:,2)];
    
            leg1='Mass 1';
            leg2='Mass 2';
    
            t_string=' Accel Transmissibility Magnitude';
    
            x_label='Frequency (Hz)';
            y_label='Trans (G/G)';
    
            md=5;
    
            [fig_num,~]=plot_loglog_function_md_two_h2(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            md=5;
            x_label='Frequency (Hz)';
            if(app.iu==1)
                y_label='Trans (in/G)';
            else
                y_label='Trans (m/G)';       
            end
    
            ppp1=[freq  app.rd_trans(:,2)];
            ppp2=[freq  app.rd_trans(:,3)];
            ppp3=[freq  rdm2m1_trans(:,2)];
    
            leg1='mass 1 - base';
            leg2='mass 2 - base';
            leg3='mass 2 - mass 1';  
    
            t_string=' Rel Disp Transmissibility Magnitude';
    
            [~,~]=plot_loglog_function_md_three_h2(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);
    
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            disp(' ');
            disp(' View plots.');
            %
            app.acc_trans(:,1)=freq;
            app.acc_trans(:,2)=acc(:,1);
            app.acc_trans(:,3)=acc(:,2);
            %
            app.acc_power_trans(:,1)=freq; 
            %
            app.rd_power_trans(:,1)=freq;
            %
            clear length;
            nnn=length(freq);
            %
            for i=1:nnn
                for j=2:3
                    app.acc_power_trans(i,j)=(app.acc_trans(i,j))^2;    
                    app.rd_power_trans(i,j)=(app.rd_trans(i,j))^2;   
                end
            end

            app.SaveButton_Trans.Enable='on';
            
            msgbox('Calculation complete.  See External Plots.  ');             
        end

        % Button pushed function: SaveButton_Trans
        function SaveButton_TransPushed(app, event)
            m=strtrim(app.ExportTrans.Value);
            n=strtrim(app.ExportAmplitude.Value);
            output_name=strtrim(app.OutputArrayNameEditFieldTrans.Value);

            if(strcmp(n,'mass 1 acceleration'))
                qdata=app.acc_trans;
                data=[qdata(:,1) qdata(:,2)];
            end
            if(strcmp(n,'mass 2 acceleration'))
                qdata=app.acc_trans;
                data=[qdata(:,1) qdata(:,3)];    
            end
            if(strcmp(n,'mass 1 - base  relative displacement'))
                qdata=app.rd_trans;
                data=[qdata(:,1) qdata(:,2)];    
            end
            if(strcmp(n,'mass 2 - base  relative displacement'))
                qdata=app.rd_trans;
                data=[qdata(:,1) qdata(:,3)];    
            end    
            if(strcmp(n,'mass 2 - mass 1'))
                qdata=app.rdm2m1_trans';
                data=[qdata(:,1) qdata(:,2)];    
            end

            if(contains(m,'^2'))
                data=fix_size(data);
                sz=size(data);
    
                for i=1:sz(1)
                    data(i,2)=data(i,2)^2;
                end
            end
    
            assignin('base', output_name, data); 
            msgbox('Data saved');
    
        end

        % Button pushed function: CalculateButton_HalfSine
        function CalculateButton_HalfSinePushed(app, event)
            check(app);
            
            fig_num=1;
                     
            Q=app.ModeShapes;
      
            A=app.BaseInputAccelGEditField.Value;
            T=app.DurationsecEditField.Value;

            amp=A;

            if(app.iu==1)
                A=A*386;
            else
                A=A*9.81;
            end

            dur1=2*T;
            dur2=10/min(app.fn);
            dur=max([dur1 dur2]);

            sr1=20/T;
            sr2=20*max(app.fn);
            sr=max([sr1 sr2]);

            dt=1/sr;

            nt=round(dur/dt);

            omega=pi/T;
            
            den=zeros(2,1);
            U1=zeros(2,1);
            U2=zeros(2,1);
            V1=zeros(2,1);
            V2=zeros(2,1);
            P=zeros(2,1);
            omegad=zeros(2,1);
            domegan=zeros(2,1);
            omn2=zeros(2,1);

            rd=zeros(nt,2);
            rv=zeros(nt,2);
            ra=zeros(nt,2);
            abase=zeros(nt,1);

            om2=omega^2;
            
            for i=1:2
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
                domegan(i)=app.dm(i)*app.omegan(i);
                omn2(i)=app.omegan(i)^2;
                den(i)=( (om2-omn2(i))^2 + (2*app.dm(i)*omega*app.omegan(i))^2);
                U1(i)=2*app.dm(i)*omega*app.omegan(i);
                U2(i)=om2-omn2(i);
                V1(i)=2*app.dm(i)*app.omegan(i)*omegad(i);
                V2(i)=om2-omn2(i)*(1-2*(app.dm(i))^2);
                P(i)=omega/omegad(i);
            end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   Transition points
        %
        t=T;
        %
        eee=exp(-domegan*t); 
        %
        arg=omega*t;
        %
        argd=omegad*t;
        %
        c1=cos(arg);
        s1=sin(arg);
        cd=cos(argd);
        sd=sin(argd);
        %
        An=app.MST*app.mm*A;
        %
        deee=zeros(2,1);
        Tn=zeros(2,1);
        Tnv=zeros(2,1);
        W=zeros(2,1);
        
        for j=1:2
        %               
            term1=U1(j)*c1 + U2(j)*s1;
            term2=V1(j)*cd(j)+V2(j)*sd(j);
            dterm1=    omega*( -U1(j)*s1 + U2(j)*c1 );
            dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
            deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
        %
            Tn(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
        %
            Tnv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
        %
            W(j)= ( Tnv(j) +   app.dm(j)*app.omegan(j)*Tn(j) )/omegad(j);
        %
        end
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        tt=zeros(nt,1);
        
        a=zeros(nt,2);
        n=zeros(2,1);
        nv=zeros(2,1);
        na=zeros(2,1);        
        
        for i=1:nt
            t=dt*(i-1);
            tt(i)=t;
            %        
            eee=exp(-domegan*t); 
            %
            arg=omega*t;
            %
            argd=omegad*t;
            %
            c1=cos(arg);
            s1=sin(arg);
            cd=cos(argd);
            sd=sin(argd);
            %
    
            for j=1:2
            %
                if(t<=T)
                    abase(i)=A*s1;
                    term1=U1(j)*c1 + U2(j)*s1;
                    term2=V1(j)*cd(j)+V2(j)*sd(j);
                    dterm1=    omega*( -U1(j)*s1 + U2(j)*c1 );
                    dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                    deee(j)=-damp(j)*app.omegan(j)*eee(j);

                    n(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);

                    nv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          

                else
                    abase(i)=0;
                    ts=t-T;

                    eee(j)=exp(-domegan(j)*ts); 
                    deee(j)=-app.dm(j)*app.omegan(j)*eee(j);                

                    arg=omega*ts;

                    argd=omegad*ts;

                    c1=cos(arg);
                    s1=sin(arg);
                    cd=cos(argd);
                    sd=sin(argd);

                     n(j)=    eee(j)*( Tn(j)*cd(j)  +W(j)*sd(j) );
                    nv(j)=   deee(j)*n(j)...
                      +omegad(j)*eee(j)*( -Tn(j)*sd(j)  +W(j)*cd(j) );           
                end

                na(j)= -2*app.dm(j)*app.omegan(j)*nv(j) -omn2(j)*n(j) -An(j)*abase(i)/A;

            end

            rd(i,:)=Q*n;
            rv(i,:)=Q*nv;
            ra(i,:)=Q*na;  

            a(i,:)=ra(i,:);        
            a(i,:)=a(i,:)+abase(i);

        end
        %
        if(app.iu==1)
           a=a/386;  
           abase=abase/386;
        else
            a=a/9.81;
            abase=abase/9.81;
            rd=rd*1000;
        end
        %
        figure(fig_num);
        fig_num=fig_num+1;
        plot(tt,rd(:,1),tt,rd(:,2));
        yl = ylim;
        ym=max(abs(yl));
        ylim([-ym,ym]);
        grid on;
        legend ('Mass 1','Mass 2'); 
        xlabel('Time(sec)');
        %
        out1=sprintf('Relative Displacement Response to Half-Sine Pulse Base Input');        
        title(out1);
        if(app.iu==1)
            ylabel('Rel Disp(in)');
        else
            ylabel('Rel Disp(mm)');    
        end

        figure(fig_num);
 
        plot(tt,abase,tt,a(:,1),tt,a(:,2));
        
        yl = ylim;
        ym=max(abs(yl));
        ylim([-ym,ym]);
        
        grid on;
        xlabel('Time(sec)');

        out1=sprintf('Acceleration Response to Half-Sine Pulse Base Input');   
        title(out1);

        ylabel('Accel(G)');

        legend ('Base Input','Mass 1','Mass 2');         

        a1=a(:,1);
        a2=a(:,2);
        rd1=rd(:,1);
        rd2=rd(:,2);

        if(max(abs(a1))<1.0e-09 )
            a1=0.;
        end
        if(max(abs(a2))<1.0e-09 )
            a2=0.;
        end 

        if(max(abs(rd1))<1.0e-09 )
            rd1=0.;
        end  
        if(max(abs(rd2))<1.0e-09 )
            rd2=0.;
        end   

        disp(out1);

        fprintf('\n %8.4g G  %8.4g sec Half-Sine Pulse \n\n',amp,T);

        disp(' ');
        disp('  Acceleration Response (G)');
        disp('              max       min  ');
        fprintf('  Mass 1:  %7.4g   %7.4g \n',max(a1),min(a1));
        fprintf('  Mass 2:  %7.4g   %7.4g \n',max(a2),min(a2));

        disp(' ');
        if(app.iu==1)
            disp('  Relative Displacement Response (inch) ');
        else
            disp('  Relative Displacement Response (mm) ');    
        end
        disp('              max      min  ');
        fprintf('  Mass 1:  %7.4g   %7.4g \n',max(rd1),min(rd1));
        fprintf('  Mass 2:  %7.4g   %7.4g \n',max(rd2),min(rd2));
        rd21=rd2-rd1;
        fprintf('\n Mass 2-Mass 1:  %7.4g   %7.4g \n',max(rd21),min(rd21));

        msgbox('Calculation complete.  See external plots.');            
        end

        % Button pushed function: CalculateButton_PSD
        function CalculateButton_PSDPushed(app, event)
            check(app);
            
            fig_num=1; 
            
            FS=app.InputArrayNameEditField_PSD.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            sz=size(app.ModeShapes);
            dof=sz(1);
            %
            THM=fix_size(THM);
            %
            sz=size(THM);
            n=sz(1);
            %
            fstart=THM(1,1);
            fend=THM(n,1);
            %
            if(fstart<=0)
                warndlg('Error: starting frequency must be >0 ');
                return;
            end
            %
            N=48;
            %
            noct=log(fend/fstart)/log(2);
            np=floor(noct*N);
            %
            freq=zeros(np,1);
            %
            R=2^(1/N);
            %
            freq(1)=fstart;
            for i=2:np
                freq(i)=freq(i-1)*R;
            end
            freq(np)=fend;
            %
            N=np;
            %
            freq=fix_size(freq);
            %
            omega=2*pi*freq;
            om2=omega.^2;
            %
            omn2=app.omegan.^2;
            %
            two_damp_omegan=zeros(dof,1);
            %
            for i=1:dof 
                two_damp_omegan(i)=2*app.dm(i)*app.omegan(i);
            end
            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %
            rd=zeros(N,dof);
            acc=zeros(N,dof);

            Y=-app.MST*app.mm;

            for i=1:N 

                 n=zeros(dof,1);

                for j=1:dof
                    A=Y(j);
                    den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                    n(j)=A/den;
                end
                na=-om2(i)*n;

               rd(i,:)=app.ModeShapes*n;
               acc(i,:)=app.ModeShapes*na;
               acc(i,:)=acc(i,:)+1;  

            end 
            %
            acc=abs(acc);
            %
            if(app.iu==1)
                app.rd_trans=386*rd;
            else
                app.rd_trans=9.81*rd;   
            end
            %
            rd21_trans=abs(app.rd_trans(:,2)-app.rd_trans(:,1)); 
            app.rd_trans=abs(app.rd_trans);
            %
            app.acc_trans=acc; 
            %
            power_acc=zeros(N,2);
            power_rd=zeros(N,2);
            power_rd21=zeros(N,2);            
            %
            for i=1:N
                for j=1:2
                    power_acc(i,j)=acc(i,j)^2;
                    power_rd(i,j)=app.rd_trans(i,j)^2;
                    power_rd21(i)=rd21_trans(i)^2;
                end
                power_rd21(i)=rd21_trans(i)^2;    
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            y=double(THM(:,2));
            x=double(THM(:,1)); 
            %
            xL=log10(x);
            yL=log10(y);
            xiL=log10(freq);
            %
            yiL = interp1(xL,yL,xiL);
            %
            clear xi;
            clear yi;
            clear freq;
            clear length;
            M=length(yiL);
            %
            xi=zeros(M,1);
            yi=zeros(M,1);
            for i=1:M
                xi(i)=10^xiL(i);
                yi(i)=10^yiL(i);
            end
            %
            freq=xi;
            %
            P=min([ length(power_acc(:,1)) length(yi)]);
            %
            app.acc_response_psd=zeros(P,2);
            app.rd_response_psd=zeros(P,2);
            app.rd21_response_psd=zeros(P,1);
            
            for i=1:P
                for j=1:2
                    app.acc_response_psd(i,j)=power_acc(i,j)*yi(i);
                    app.rd_response_psd(i,j)= power_rd(i,j)*yi(i);
                end
                app.rd21_response_psd(i)= power_rd21(i)*yi(i);
            end
            %
            temp=freq;
            clear freq;
            freq=temp(1:P);
            %
            [~,accel_rms_1]=calculate_PSD_slopes_no( freq,app.acc_response_psd(:,1)  );
            [~,accel_rms_2]=calculate_PSD_slopes_no( freq,app.acc_response_psd(:,2)  );
            %
            [~,disp_rms_1]=calculate_PSD_slopes_no(  freq,app.rd_response_psd(:,1)   );
            [~,disp_rms_2]=calculate_PSD_slopes_no(  freq,app.rd_response_psd(:,2)   );
            [~,disp_rms_21]=calculate_PSD_slopes_no(  freq,app.rd21_response_psd   );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                dd='in'; 
            else
                dd='mm';
            
                disp_rms_1=disp_rms_1*1000;
                disp_rms_2=disp_rms_2*1000; 
                disp_rms_21=disp_rms_21*1000;    
            end
            %

            disp(' ');
            disp('Acceleration Response ');
            fprintf(' Mass 1:  %8.4g GRMS \n',accel_rms_1);
            fprintf(' Mass 2:  %8.4g GRMS \n\n',accel_rms_2);
            
            disp(' ');
            disp(' Relative Displacement Response ');
            fprintf(' Mass 1:  %8.4g %s RMS \n',disp_rms_1,dd);
            fprintf(' Mass 2:  %8.4g %s RMS \n\n',disp_rms_2,dd);
           
            fprintf(' Mass 2-Mass 1:  %8.4g %s RMS \n',disp_rms_21,dd);
            %
            if(app.iu==1)
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
            else
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes_no( THM(:,1),THM(:,2)  );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            freq=fix_size(freq);
            %
            t_string='Acceleration Response PSD ';
            leg1=sprintf('Base Input %6.3g GRMS',input_rms);
            leg2=sprintf('Mass 1 Response %6.3g GRMS',accel_rms_1);
            leg3=sprintf('Mass 2 Response %6.3g GRMS',accel_rms_2);    
            
            ppp1=THM;
            ppp2=[freq app.acc_response_psd(:,1)];
            ppp3=[freq app.acc_response_psd(:,2)];
           
            app.a1_response_psd=ppp2;
            app.a2_response_psd=ppp3;
            
            md=5;
            x_label='Frequency (Hz)';
            y_label='Accel (G^2/Hz)';
            fmin=THM(1,1);
            fmax=THM(end,1);

            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);

            if(app.iu==2)
                app.rd_response_psd=app.rd_response_psd*1.0e+06;
                app.rd21_response_psd=app.rd21_response_psd*1.0e+06;
            end

            app.rd21_response_psd=fix_size(app.rd21_response_psd);

            ppp1=[freq app.rd_response_psd(:,1)];
            ppp2=[freq app.rd_response_psd(:,2)];
            ppp3=[freq app.rd21_response_psd];
            
            app.rd1_response_psd=ppp1;
            app.rd2_response_psd=ppp2;            
            app.rd21_response_psd=ppp3;

            t_string='Relative Displacement Response PSD';
            
            leg1=sprintf('mass 1 - base    %6.3g %s RMS',disp_rms_1,dd);
            leg2=sprintf('mass 2 - base    %6.3g %s RMS',disp_rms_2,dd);
            leg3=sprintf('mass 2 - mass 1  %6.3g %s RMS',disp_rms_21,dd);
        
            y_label=ylab_rd;
            md=6;
            
            [~]=plot_loglog_function_md_three(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);
           
            app.SaveButton_PSD.Enable='on';   
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)
            
            try
                V=strtrim(app.OutputArrayListBox.Value);

                if(strcmp(V,'Acceleration Mass 1'))
                    data=app.a1_response_psd;
                end
                if(strcmp(V,'Acceleration Mass 2'))
                    data=app.a2_response_psd;            
                end
                if(strcmp(V,'Relative Displacement Mass 1'))
                    data=app.rd1_response_psd;                
                end
                if(strcmp(V,'Relative Displacement Mass 2'))
                    data=app.rd2_response_psd;                
                end
                if(strcmp(V,'Relative Displacement Mass 2 - Mass 1'))
                    data=app.rd21_response_psd;
                end
            
                output_name=app.OutputArrayNameEditField_PSD.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end
        end

        % Button pushed function: CalculateButton_SRS
        function CalculateButton_SRSPushed(app, event)
            check(app);
            
            FS=app.InputArrayNameEditField_SRS.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end   
            
            num_srs=length(THM(:,1));
            
            Q=app.SpecificationQEditField.Value;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            num_fn=length(app.fn);      

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fig_num=1;
            
            md=4;
            t_string=sprintf('SRS Q=%g',Q);
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            
            ppp=THM;
            fmin=THM(1,1);
            fmax=THM(end,1);
            
            [~]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.fn(1)<THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            if(app.fn(num_fn)>THM(num_srs,1))
               warndlg(' Last Natural Frequency < Last Specification Frequency ');
               return
            end 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
  
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  

            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);

             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);
  
            f=THM(:,1);
            a=THM(:,2);

            slope=zeros(num_srs-1,1);

            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end

            for i=1:num_fn
  
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 
    
            for i=1:num_fn
   
                for j=1:num_fn
        
                    PFM=app.pff(j)*app.ModeShapes(i,j);
    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
  
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
          
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
  
                end
    
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
       
            end

            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end

            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   

            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
    
            disp(' ');
            disp(' Acceleration (G)');
            disp(' ');
            disp('dof    SRSS    Abssum' );

            for i=1:num_fn
                fprintf(' %d  %8.4g  %8.4g \n',i,accel_srss(i),accel_abssum(i));
            end

            disp(' ');

            if(app.iu==1)
                disp(' Pseudo Velocity (in/sec)');
            else
                disp(' Pseudo Velocity (cm/sec)');    
            end
            disp(' ');
            disp('dof    SRSS    Abssum' );

            for i=1:num_fn
                fprintf(' %d  %8.4g  %8.4g \n',i,pv_srss(i),pv_abssum(i));
            end

            disp(' ');

            if(app.iu==1)
                disp(' Relative Displacement (in)');
            else
                disp(' Relative Displacement  (mm)');    
            end    
    
            disp(' ');
            disp('dof   SRSS    Abssum' );

            for i=1:num_fn
                fprintf(' %d  %8.4g  %8.4g \n',i,rd_srss(i),rd_abssum(i));
            end

            msgbox('Results written to Command Window');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UITwodofBaseaaUIFigure = uifigure('Visible', 'off');
            app.UITwodofBaseaaUIFigure.Name = 'UI Two dof Base aa';
            app.UIAxes = uiaxes(app.UITwodofBaseaaUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UITwodofBaseaaUIFigure);
            app.NaturalFreqTab = uitab(app.TabGroup);
            app.NaturalFreqTab.Title = 'Natural Freq';
            app.Mass2EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Mass2EditFieldLabel.Text = 'Mass 2';
            app.Mass2EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Mass1EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Mass1EditFieldLabel.Text = 'Mass 1';
            app.Mass1EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Stiffness2EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Stiffness2EditFieldLabel.Text = 'Stiffness 2';
            app.Stiffness2EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Stiffness1EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Stiffness1EditFieldLabel.Text = 'Stiffness 1';
            app.Stiffness1EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.UnitsListBoxLabel = uilabel(app.NaturalFreqTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.NaturalFreqTab);
            app.UnitsListBox.Items = {'English:  mass (lbm) & stiffness (lbf/in)', 'Metric:    mass (kg) & stiffness (N/m)', ''};
            app.UnitsListBox.Value = 'English:  mass (lbm) & stiffness (lbf/in)';
            app.CalculateButton = uibutton(app.NaturalFreqTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.SelectTypesLabel = uilabel(app.DampingTab);
            app.SelectTypesLabel.Text = 'Select Types';
            app.Label = uilabel(app.DampingTab);
            app.Label.Text = '';
            app.ListBoxQ = uilistbox(app.DampingTab);
            app.ListBoxQ.Items = {'Amplification Factor Q', 'Viscous Damping Coefficient'};
            app.ListBoxQ.ValueChangedFcn = createCallbackFcn(app, @ListBoxQValueChanged, true);
            app.ListBoxQ.Value = 'Amplification Factor Q';
            app.Label_2 = uilabel(app.DampingTab);
            app.Label_2.Text = '';
            app.ListBoxUniform = uilistbox(app.DampingTab);
            app.ListBoxUniform.Items = {'Uniform', 'Varies by Mode'};
            app.ListBoxUniform.ValueChangedFcn = createCallbackFcn(app, @ListBoxUniformValueChanged, true);
            app.ListBoxUniform.Value = 'Uniform';
            app.Mode1EditFieldLabel = uilabel(app.DampingTab);
            app.Mode1EditFieldLabel.Text = 'Mode 1';
            app.Mode1EditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingUnitLabel = uilabel(app.DampingTab);
            app.DampingUnitLabel.Text = 'DampingUnit';
            app.Mode2EditFieldLabel = uilabel(app.DampingTab);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.DampingTab, 'numeric');
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.TransTab = uitab(app.TabGroup);
            app.TransTab.Title = 'Trans';
            app.CalculateButton_Trans = uibutton(app.TransTab, 'push');
            app.CalculateButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_TransPushed, true);
            app.CalculateButton_Trans.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.TransTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.TransTab, 'numeric');
            app.EndFreqHzEditFieldLabel = uilabel(app.TransTab);
            app.EndFreqHzEditFieldLabel.Text = {'End Freq (Hz)'; ''};
            app.EndFreqHzEditField = uieditfield(app.TransTab, 'numeric');
            app.SaveButton_Trans = uibutton(app.TransTab, 'push');
            app.SaveButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_TransPushed, true);
            app.SaveButton_Trans.Text = 'Save';
            app.Label_3 = uilabel(app.TransTab);
            app.Label_3.Text = '';
            app.ExportTrans = uilistbox(app.TransTab);
            app.ExportTrans.Items = {'Transmissibility   -  freq (Hz) & (response/input)        ', 'Power Transmissibility   -  freq (Hz) & (response/input)^2', ''};
            app.ExportTrans.Value = 'Transmissibility   -  freq (Hz) & (response/input)        ';
            app.ExportArrayLabel = uilabel(app.TransTab);
            app.ExportArrayLabel.Text = 'Export Array';
            app.Label_4 = uilabel(app.TransTab);
            app.Label_4.Text = '';
            app.ExportAmplitude = uilistbox(app.TransTab);
            app.ExportAmplitude.Items = {'mass 1 acceleration                   ', 'mass 2 acceleration                   ', 'mass 1 - base  relative displacement  ', 'mass 2 - base  relative displacement  ', 'mass 2 - mass 1  relative displacement'};
            app.ExportAmplitude.Value = 'mass 1 acceleration                   ';
            app.AmplitudeTypeLabel = uilabel(app.TransTab);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.OutputArrayNameEditField_2Label = uilabel(app.TransTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditFieldTrans = uieditfield(app.TransTab, 'text');
            app.SteadySineTab = uitab(app.TabGroup);
            app.SteadySineTab.Title = 'Steady Sine';
            app.AccelGEditFieldLabel = uilabel(app.SteadySineTab);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.SteadySineTab, 'numeric');
            app.SteadyStateSineBaseInputLabel = uilabel(app.SteadySineTab);
            app.SteadyStateSineBaseInputLabel.Text = 'Steady-State Sine Base Input';
            app.FreqHzEditFieldLabel = uilabel(app.SteadySineTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SteadySineTab, 'numeric');
            app.CalculateButton_Sine = uibutton(app.SteadySineTab, 'push');
            app.CalculateButton_Sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SinePushed, true);
            app.CalculateButton_Sine.Text = 'Calculate';
            app.SettoFirstModalFreqButton = uibutton(app.SteadySineTab, 'push');
            app.SettoFirstModalFreqButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstModalFreqButtonPushed, true);
            app.SettoFirstModalFreqButton.Text = 'Set to First Modal Freq';
            app.HalfSineTab = uitab(app.TabGroup);
            app.HalfSineTab.Title = 'Half-Sine';
            app.CalculateButton_HalfSine = uibutton(app.HalfSineTab, 'push');
            app.CalculateButton_HalfSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_HalfSinePushed, true);
            app.CalculateButton_HalfSine.Text = 'Calculate';
            app.BaseInputAccelGEditFieldLabel = uilabel(app.HalfSineTab);
            app.BaseInputAccelGEditFieldLabel.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.ResponsetoHalfSineBaseInputLabel = uilabel(app.HalfSineTab);
            app.ResponsetoHalfSineBaseInputLabel.Text = 'Response to Half-Sine Base Input';
            app.DurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.ArbitraryTab = uitab(app.TabGroup);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.CalculateButton_Arbitrary = uibutton(app.ArbitraryTab, 'push');
            app.CalculateButton_Arbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_ArbitraryPushed, true);
            app.CalculateButton_Arbitrary.Text = 'Calculate';
            app.TheinputarraymusthavetwocolumnsTimesecAccelGLabel = uilabel(app.ArbitraryTab);
            app.TheinputarraymusthavetwocolumnsTimesecAccelGLabel.Text = {'The input array must have two columns:  '; '  Time(sec) & Accel (G)'};
            app.ResponsetoarbitrarybaseinputLabel = uilabel(app.ArbitraryTab);
            app.ResponsetoarbitrarybaseinputLabel.Text = 'Response to arbitrary base input.';
            app.InputArrayNameEditFieldLabel = uilabel(app.ArbitraryTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.ArbitraryTab, 'text');
            app.SelectOutputListBoxLabel = uilabel(app.ArbitraryTab);
            app.SelectOutputListBoxLabel.Text = 'Select Output';
            app.SelectOutputListBox = uilistbox(app.ArbitraryTab);
            app.SelectOutputListBox.Items = {'Acceleration Mass 1', 'Acceleration Mass 2', 'Relative Disp Mass 1', 'Relative Disp Mass 2', 'Relative Disp Mass 2 - Mass 1'};
            app.SelectOutputListBox.Value = 'Acceleration Mass 1';
            app.SaveButton = uibutton(app.ArbitraryTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ArbitraryTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ArbitraryTab, 'text');
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.CalculateButton_PSD = uibutton(app.PSDTab, 'push');
            app.CalculateButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PSDPushed, true);
            app.CalculateButton_PSD.Text = 'Calculate';
            app.InputArrayNamePSD = uilabel(app.PSDTab);
            app.InputArrayNamePSD.Text = {'The input array must have two columns:  Freq(Hz) & Accel PSD(G^2/Hz)'; ''};
            app.InputArrayNameEditField_3Label = uilabel(app.PSDTab);
            app.InputArrayNameEditField_3Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_PSD = uieditfield(app.PSDTab, 'text');
            app.SaveButton_PSD = uibutton(app.PSDTab, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.PSDTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.PSDTab, 'text');
            app.OutputArrayListBoxLabel = uilabel(app.PSDTab);
            app.OutputArrayListBoxLabel.Text = 'Output Array';
            app.OutputArrayListBox = uilistbox(app.PSDTab);
            app.OutputArrayListBox.Items = {'Acceleration Mass 1                  ', 'Acceleration Mass 2                  ', 'Relative Displacement Mass 1         ', 'Relative Displacement Mass 2         ', 'Relative Displacement Mass 2 - Mass 1', ''};
            app.OutputArrayListBox.Value = 'Acceleration Mass 1                  ';
            app.SRSTab = uitab(app.TabGroup);
            app.SRSTab.Title = 'SRS';
            app.CalculateButton_SRS = uibutton(app.SRSTab, 'push');
            app.CalculateButton_SRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SRSPushed, true);
            app.CalculateButton_SRS.Text = 'Calculate';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.SRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = 'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)';
            app.InputArrayNameEditField_2Label = uilabel(app.SRSTab);
            app.InputArrayNameEditField_2Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_SRS = uieditfield(app.SRSTab, 'text');
            app.SpecificationQEditFieldLabel = uilabel(app.SRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.SRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.two_dof_base_aaver10byTomIrvineLabel = uilabel(app.UITwodofBaseaaUIFigure);
            app.two_dof_base_aaver10byTomIrvineLabel.Text = {'two_dof_base_aa  ver 1.1  by Tom Irvine     Response of a two-dof system to base excitation.'; '  '};
            app.ReturnButton = uibutton(app.UITwodofBaseaaUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UITwodofBaseaaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_base_aa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UITwodofBaseaaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UITwodofBaseaaUIFigure)
        end
    end
end