classdef two_dof_force_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        twodofforceUIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SelectSystemTab                 matlab.ui.container.Tab
        SelectButton_3                  matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        GoToModalAnalysisButton         matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        ModalAnalysisTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MassStiffnessTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        EnterValuesLabel                matlab.ui.control.Label
        ModalAnalysisTab_2              matlab.ui.container.Tab
        MassNormalizedEigenvectorsTextArea  matlab.ui.control.TextArea
        MassNormalizedEigenvectorsTextAreaLabel  matlab.ui.control.Label
        StiffnessMatrixTextArea         matlab.ui.control.TextArea
        StiffnessMatrixTextAreaLabel    matlab.ui.control.Label
        MassMatrixTextArea              matlab.ui.control.TextArea
        MassMatrixTextAreaLabel         matlab.ui.control.Label
        NaturalFrequenciesTextArea      matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        ModeShapesEditField             matlab.ui.control.EditField
        ModeShapesEditFieldLabel        matlab.ui.control.Label
        NaturalFrequenciesEditField     matlab.ui.control.EditField
        NaturalFrequenciesEditFieldLabel  matlab.ui.control.Label
        StiffnessEditField              matlab.ui.control.EditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        MassEditField                   matlab.ui.control.EditField
        MassEditFieldLabel              matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Image_modal                     matlab.ui.control.Image
        DampingTab                      matlab.ui.container.Tab
        Mode2EditField                  matlab.ui.control.NumericEditField
        Mode2EditFieldLabel             matlab.ui.control.Label
        Mode1EditField                  matlab.ui.control.NumericEditField
        Mode1EditFieldLabel             matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        DampingUnitLabel                matlab.ui.control.Label
        ListBoxUniform                  matlab.ui.control.ListBox
        ListBoxQ                        matlab.ui.control.ListBox
        SelectTypesLabel                matlab.ui.control.Label
        ForcedResponseTab               matlab.ui.container.Tab
        ResultsReadOnlyTextArea         matlab.ui.control.TextArea
        ResultsReadOnlyTextAreaLabel    matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        FRFTab_2                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        CalculateButton_Trans           matlab.ui.control.Button
        ResponsedofListBox              matlab.ui.control.ListBox
        ResponsedofListBoxLabel         matlab.ui.control.Label
        ForcedofListBox                 matlab.ui.control.ListBox
        ForcedofListBoxLabel            matlab.ui.control.Label
        EndFreqHzEditField              matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.NumericEditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        ExportTab_2                     matlab.ui.container.Tab
        OutputArrayNameEditFieldTrans   matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        SaveButton_Trans                matlab.ui.control.Button
        ExportAmplitude                 matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        ExportTrans                     matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        AmplitudeTypeLabel              matlab.ui.control.Label
        ExportFormatLabel               matlab.ui.control.Label
        SineTab_2                       matlab.ui.container.Tab
        ForcelbfEditField               matlab.ui.control.NumericEditField
        ForcelbfEditFieldLabel          matlab.ui.control.Label
        AppliedForcedofListBox          matlab.ui.control.ListBox
        AppliedForcedofListBoxLabel     matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        SettoFirstModalFreqButton       matlab.ui.control.Button
        CalculateButton_Sine            matlab.ui.control.Button
        SteadyStateAppliedForceLabel    matlab.ui.control.Label
        HalfSineTab_2                   matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CalculateTab_2                  matlab.ui.container.Tab
        TotalDurationsecEditField       matlab.ui.control.NumericEditField
        TotalDurationsecEditFieldLabel  matlab.ui.control.Label
        CalculateButton_HalfSine        matlab.ui.control.Button
        AppliedForcedofListBox_HS       matlab.ui.control.ListBox
        AppliedForcedofListBox_2Label   matlab.ui.control.Label
        PulseDurationsecEditField       matlab.ui.control.NumericEditField
        PulseDurationsecEditFieldLabel  matlab.ui.control.Label
        AppliedHSForceEditField         matlab.ui.control.NumericEditField
        AppliedForcelbfLabel            matlab.ui.control.Label
        ResponsetoHalfSineAppliedForceLaplaceTransformLabel  matlab.ui.control.Label
        ExportTab_3                     matlab.ui.container.Tab
        SaveDiracButton_3               matlab.ui.control.Button
        OutputArrayNameEditField_HS     matlab.ui.control.EditField
        OutputArrayNameEditField_3Label  matlab.ui.control.Label
        SaveHSButton                    matlab.ui.control.Button
        OutputTimeHistoryArrayListBox   matlab.ui.control.ListBox
        OutputTimeHistoryArrayListBoxLabel  matlab.ui.control.Label
        ArbitraryTab_2                  matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        CalculateTab_3                  matlab.ui.container.Tab
        SaveDiracButton_5               matlab.ui.control.Button
        UpdateListButton_arb_force      matlab.ui.control.Button
        SelectInputArrayListBox_arb_force  matlab.ui.control.ListBox
        SelectInputArrayListBox_5Label  matlab.ui.control.Label
        AppliedForcedofListBox_Arbitrary  matlab.ui.control.ListBox
        AppliedForcedofListBox_2Label_2  matlab.ui.control.Label
        CalculateButton_Arbitrary       matlab.ui.control.Button
        InputArbitraryForceArray        matlab.ui.control.Label
        ResponsetoarbitraryappliedforceLabel  matlab.ui.control.Label
        ExportTab_5                     matlab.ui.container.Tab
        SaveDiracButton_4               matlab.ui.control.Button
        OutputArrayNameEditField_arb    matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveArbitraryButton             matlab.ui.control.Button
        OutputTimeHistoryArrayListBox_Arbitrary  matlab.ui.control.ListBox
        OutputTimeHistoryArrayListBox_2Label  matlab.ui.control.Label
        PSDTab_2                        matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        CalculateTab_4                  matlab.ui.container.Tab
        UpdateListButton_arb_force_2    matlab.ui.control.Button
        SelectInputPSDArrayListBox      matlab.ui.control.ListBox
        SelectInputArrayListBox_5Label_2  matlab.ui.control.Label
        AppliedForcedofListBox_PSD      matlab.ui.control.ListBox
        AppliedForcedofListBox_3Label   matlab.ui.control.Label
        CalculateButton_PSD             matlab.ui.control.Button
        InputArrayNamePSD               matlab.ui.control.Label
        ExportTab_6                     matlab.ui.container.Tab
        OutputArrayListBox_psd          matlab.ui.control.ListBox
        OutputArrayListBoxLabel         matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        SaveButton_PSD                  matlab.ui.control.Button
        DiracDeltaTab_2                 matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        CaculateTab                     matlab.ui.container.Tab
        TotalImpulselbfsecEditField_Dirac  matlab.ui.control.NumericEditField
        TotalImpulselbfsecEditField_Label_Dirac  matlab.ui.control.Label
        CalculateButton_Dirac           matlab.ui.control.Button
        SampleRateHzEditField_Dirac     matlab.ui.control.NumericEditField
        SampleRateHzEditField_Label_Dirac  matlab.ui.control.Label
        TotalDurationsecEditField_Dirac  matlab.ui.control.NumericEditField
        TotalDurationsecEditField_DiracLabel  matlab.ui.control.Label
        ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel  matlab.ui.control.Label
        ExportTab_4                     matlab.ui.container.Tab
        OutputArrayNameEditField_Dirac  matlab.ui.control.EditField
        OutputArrayNameEditField_Label_Dirace  matlab.ui.control.Label
        SaveDiracButton                 matlab.ui.control.Button
        OutputTimeHistoryArrayListBox_Dirac  matlab.ui.control.ListBox
        OutputTimeHistoryArrayListBox_Label_Dirace  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        two_dof_force_aver12byTomIrvineLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        Menu_3                          matlab.ui.container.Menu
        Menu2_3                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        FD1
        FD2
        model
        iu
        M
        K
        fn
        dm
        damp1
        damp2
        omegan
        ModeShapes
        MST  
        pff
        ppf
        zflag
        force_unit
        d_f_mag
        v_f_mag
        a_f_mag

        a_f_mag_phase
        a_f_mag_complex        
        v_f_mag_phase
        v_f_mag_complex   
        d_f_mag_phase
        d_f_mag_complex 

        displacement
        velocity
        acceleration
        rel_disp

    end
    
    methods (Access = private)

        function Update_psd(app)
            ClosePlots(app);
            app.ResultsReadOnlyTextArea.Value='';
            
            [cell_array]=update_power_spectral_density();

            try    
                app.SelectInputPSDArrayListBox.Items=unique(sort(cell_array));
            catch
            end

        end

        function update_arb_force(app)

            ClosePlots(app);
            app.ResultsReadOnlyTextArea.Value='';

            [cell_array]=update_time_history();
           
            try
                app.SelectInputArrayListBox_arb_force.Items=unique(sort(cell_array));
                app.SelectInputArrayListBox_arb_force.Value=app.SelectInputArrayListBox_arb_force.Items{1};
            catch
            end
        end   


        function check(app)

            app.ResultsReadOnlyTextArea.Value='';
            ClosePlots(app);
        
            app.zflag=0;
        
            if(isempty(app.fn))
                app.zflag=1;
                warndlg('Perform Modal Analysis first')
                return;                
            end
            if(isempty(app.damp1)||isempty(app.damp2))
                app.zflag=1;
                warndlg('Run Damping Tab')
                return;                
            end            
        
            
            try
                if(app.damp1>=0 && app.damp2>=0)
                else
                    app.zflag=1;
                    warndlg('Enter Damping at Damping Tab');
                    return;                
                end
            catch
                app.zflag=1;
                warndlg('Enter Damping at Damping Tab');
                return;
            end
            
        end

        function ChangeDamping(app)

            app.ResultsReadOnlyTextArea.Value='';

            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                app.DampingUnitLabel.Text = 'Q';
            else
                app.DampingUnitLabel.Text = 'Ratio';
            end
            
            if(contains(m,'Uniform'))
                app.Mode1EditFieldLabel.Text='Uniform';
                app.Mode2EditField.Visible = 'off';
                app.Mode2EditFieldLabel.Visible = 'off';                
            else
                app.Mode1EditFieldLabel.Text='Mode 1';                
                app.Mode2EditField.Visible = 'on';
                app.Mode2EditFieldLabel.Visible = 'on';                 
            end            

        end        


        function clear_modal(app)
            app.MassMatrixTextArea.Visible='off';
            app.StiffnessMatrixTextArea.Visible='off';
            app.MassNormalizedEigenvectorsTextArea.Visible='off';
            app.NaturalFrequenciesTextArea.Visible='off';
            app.MassMatrixTextAreaLabel.Visible='off';
            app.StiffnessMatrixTextAreaLabel.Visible='off';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='off';
            app.NaturalFrequenciesTextAreaLabel.Visible='off';            
        end    

        function ModelChange(app) 
            
            clear_modal(app);

            app.ResultsReadOnlyTextArea.Value='';

            if(app.model==1)
                app.Image_modal.ImageSource='two_dof_force_1.jpg';
            end  
            if(app.model==2)
                app.Image_modal.ImageSource='two_dof_force_2.jpg';
            end            
            if(app.model==3)
                app.Image_modal.ImageSource='two_dof_force_3.jpg';
            end 

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                mu='lbm';
                ku='lbf/in';
                app.force_unit='lbf';
                app.AppliedForcelbfLabel.Text='Force (lbf)';
                app.ForcelbfEditFieldLabel.Text='Force (lbf)';
                app.TotalImpulselbfsecEditField_Label_Dirac.Text='Total Impulse (lbf sec)';
                app.InputArbitraryForceArray.Text='The input array must have two columns:  Time(sec) & Force (lbf)';
                app.InputArrayNamePSD.Text='The PSD input array must have two columns:  Freq(Hz) & Force(lbf^2/Hz)';
            else
                mu='N';
                ku='N/m'; 
                app.force_unit='N';
                app.AppliedForcelbfLabel.Text='Force (N)';
                app.ForcelbfEditFieldLabel.Text='Force (N)';
                app.TotalImpulselbfsecEditField_Label_Dirac.Text='Total Impulse (N sec)';
                app.InputArbitraryForceArray.Text='The input array must have two columns:  Time(sec) & Force (N)';   
                app.InputArrayNamePSD.Text='The PSD input array must have two columns:  Freq(Hz) & Force(N^2/Hz)';                
            end

            if(app.model==1)
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='k';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';    
                ss{1,3}=mu;
                ss{2,3}=mu; 
                ss{3,3}=ku;               
            end  
            if(app.model==2)
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='k1';
                ss{4,1}='k2';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';        
                ss{1,3}=mu;
                ss{2,3}=mu; 
                ss{3,3}=ku;
                ss{4,3}=ku;          
            end            
            if(app.model==3)
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='k1';
                ss{4,1}='k2';
                ss{5,1}='k3';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';
                ss{4,2}='';  
                ss{5,2}='';              
                ss{1,3}=mu;
                ss{2,3}=mu; 
                ss{3,3}=ku;
                ss{4,3}=ku;
                ss{5,3}=ku;  
            end 

            app.UITable.Data=ss; 

        end    
        

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            app.model=1;
            ModelChange(app);
            ChangeDamping(app);
            update_arb_force(app);
            Update_psd(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function ModelListBoxValueChanged(app, event)
            ModelChange(app);
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear_modal(app);

            Y=char(app.UITable.Data);
            sz=size(Y);
            N=round(sz(1)/3); 


            if(app.model==1)
                m1=str2num(Y(N+1,:));
                m2=str2num(Y(N+2,:));
                k =str2num(Y(N+3,:));
                
                app.K=[k -k; -k k];
                
            end            
            if(app.model==2)
                m1=str2num(Y(N+1,:));
                m2=str2num(Y(N+2,:));
                k1=str2num(Y(N+3,:));
                k2=str2num(Y(N+4,:)); 
                
                app.K=[(k1+k2) -k2; -k2 k2];
                
            end
            if(app.model==3)

                m1=str2num(Y(N+1,:));
                m2=str2num(Y(N+2,:));
                k1=str2num(Y(N+3,:));
                k2=str2num(Y(N+4,:)); 
                k3=str2num(Y(N+5,:));   

                app.K=[(k1+k2) -k2; -k2 (k2+k3)];

            end

            app.M=zeros(2,2);

            try
                app.M(1,1)=m1;
                app.M(2,2)=m2;
            catch
                warndlg('Enter mass & stiffness first');
                return;          
            end

            if(app.iu==1)
               app.M=app.M/386.;
            end
            
            [app.fn,app.omegan,app.ModeShapes,app.MST]=Generalized_Eigen(app.K,app.M,2);
        

            %
            dof=length(app.fn);
            %
            v=ones(dof,1)';

            if(app.iu==1)
                s1='Mass Matrix (lbf sec^2/in)';
            else
                s1='Mass Matrix (kg)';    
            end
            app.MassMatrixTextAreaLabel.Text=s1;

            m=app.M;
            ss_m=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',m(1,1),m(1,2),m(2,1),m(2,2));
            
            k=app.K;
            if(app.iu==1)
                s2='Stiffness Matrix (lbf/in)';
            else
                s2='Stiffness Matrix (N/m)';    
            end
            app.StiffnessMatrixTextAreaLabel.Text=s2; 
            ss_k=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',k(1,1),k(1,2),k(2,1),k(2,2));

            e=app.ModeShapes;
            ss_e=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',e(1,1),e(1,2),e(2,1),e(2,2));
            app.MassNormalizedEigenvectorsTextArea.Value=ss_e;


                ss_f='            Natural      Participation    Effective Modal';
                
                if(app.iu==1)
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (lbm)';
                else
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (kg)';    
                end    


            ss_f=sprintf('%s\n%s\n',ss_f,ss_ff);
            
            %
            LM=app.MST*app.M*v';
            pf=LM;
            sum=0;
            %    
            mmm=app.MST*app.M*app.ModeShapes;   
            %
            
            app.pff=zeros(dof,1);
            emm=zeros(dof,1);
            
            app.fn(abs(app.fn)<1.0e-05) = 0;
            
            for i=1:dof
                    app.pff(i)=pf(i)/mmm(i,i);
                    emm(i)=pf(i)^2/mmm(i,i);
                    sum=sum+emm(i); 

                   
                        if(app.iu==1)
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i)*386);           
                        else
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t   %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i));            
                        end
          

                    ss_f=sprintf('%s\n%s',ss_f,out1);
            end
            if(app.iu==1)
                out1=sprintf('\n modal mass sum = %8.4g lbm \n',sum*386);
            else
                out1=sprintf('\n modal mass sum = %8.4g kg \n',sum);    
            end

          
                ss_fn=sprintf('%s\n%s\n',ss_f,out1);
      

            app.MassMatrixTextArea.Value=ss_m;
            app.StiffnessMatrixTextArea.Value=ss_k;
            app.NaturalFrequenciesTextArea.Value=ss_fn; 

            app.MassMatrixTextArea.Visible='on';
            app.StiffnessMatrixTextArea.Visible='on';
            app.MassNormalizedEigenvectorsTextArea.Visible='on';
            app.NaturalFrequenciesTextArea.Visible='on';
            app.MassMatrixTextAreaLabel.Visible='on';
            app.StiffnessMatrixTextAreaLabel.Visible='on';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='on';
            app.NaturalFrequenciesTextAreaLabel.Visible='on';   

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            ModelChange(app);
        end

        % Callback function
        function UITableCellEdit(app, event)

            clear_modal(app);
        end

        % Callback function
        function UITableCellSelection(app, event)
           
            clear_modal(app);
        end

        % Callback function
        function UITableKeyPress(app, event)
            
            clear_modal(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name_mass=app.MassEditField.Value;
            name_stiff=app.StiffnessEditField.Value;
            name_fn=app.NaturalFrequenciesEditField.Value;
            name_modes=app.ModeShapesEditField.Value;

            assignin('base',name_mass, app.M);            
            assignin('base',name_stiff, app.K);            
            assignin('base',name_fn, app.fn);
            assignin('base',name_modes, app.ModeShapes);
            
            msgbox('Data saved');   
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                if(contains(m,'Uniform'))
                     Q1=app.Mode1EditField.Value;
                     Q2=Q1;
                     app.damp1=1/(2*Q1);
                     app.damp2=app.damp1;
                else
                     Q1=app.Mode1EditField.Value;
                     Q2=app.Mode2EditField.Value;
                     app.damp1=1/(2*Q1);
                     app.damp2=1/(2*Q2);                
                end    
            else
                if(contains(m,'Uniform'))
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.damp1;
                     Q1=1/(2*app.damp1);
                     Q2=Q1;              
                else
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.Mode2EditField.Value;
                     Q1=1/(2*app.damp1);
                     Q2=1/(2*app.damp2);                   
                end                   
            end         
            
            app.dm=[app.damp1 app.damp2];
            
            fprintf('\n Damping Ratios \n Mode 1       2        \n');
            fprintf('    %6.3g  %6.3g  \n',app.damp1,app.damp2);

            msgbox('Damping Saved');

        end

        % Value changed function: ListBoxQ
        function ListBoxQValueChanged(app, event)
   
            ChangeDamping(app);
        end

        % Value changed function: ListBoxUniform
        function ListBoxUniformValueChanged(app, event)
            
            ChangeDamping(app);
        end

        % Callback function
        function SettoFirstModalFreqButtonPushed(app, event)
            try
                ff=str2double(sprintf('%8.4g',app.fn(1)));
                app.FreqHzEditField.Value=ff;           
            catch
                warndlg('Perform Modal Analysis First')
                return;
            end
        end

        % Callback function
        function CalculateButton_SinePushed(app, event)
         
          
        end

        % Value changed function: ForcelbfEditField
        function ForcelbfEditFieldValueChanged(app, event)
   
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: AppliedForcedofListBox
        function AppliedForcedofListBoxValueChanged(app, event)
          
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: SettoFirstModalFreqButton
        function SettoFirstModalFreqButtonPushed2(app, event)
            try
                ff=str2double(sprintf('%8.4g',app.fn(1)));
                app.FreqHzEditField.Value=ff;           
            catch
                warndlg('Run Natural Frequency Tab')
                return;
            end
        end

        % Button pushed function: CalculateButton_Sine
        function CalculateButton_SinePushed2(app, event)
               check(app);
            
            if(app.zflag==1)
                return;
            end
        
            str=app.AppliedForcedofListBox.Value;
            
            if(contains(str,'1'))
                k=1;
            else
                k=2;
            end
            
            freq=app.FreqHzEditField.Value; 
            amp=app.ForcelbfEditField.Value;
            
            if(amp<1.0e-06)
                warndlg('Enter Force > 0 ')
                return;    
            end
            
            
            fprintf('\n Applied Force \n');
            fprintf('   %8.4g %s  %8.4g Hz\n\n',amp,app.force_unit,freq);

            nrb=0;  % leave as zero

            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
            
            nf=1;
            
            tpi=2*pi;

            omn=tpi*fnv;
            omn2=omn.*omn;

            omega=zeros(nf,1);
            omega2=zeros(nf,1);

            for i=1:nf
                omega(i)=2*pi*freq(i);
                omega2(i)=(omega(i))^2;
            end

            app.d_f_mag=zeros(2,1);
            app.v_f_mag=zeros(2,1);
            app.a_f_mag=zeros(2,1);

            h_disp_complex=zeros(2,1);

            num_columns=2;

            for i=1:2

                iam=1;
                [H_disp_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_disp=abs(H_disp_force);
                app.d_f_mag(i)=amp*HM_disp;
                h_disp_complex(i)=H_disp_force;
      
                iam=2;
                [H_velox_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_velox=abs(H_velox_force);
                app.v_f_mag(i)=amp*HM_velox;

                iam=3;
                [H_accel_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);

                if(app.iu==1)
                    scale=386;
                else
                    scale=9.81;
                end
    
                H_accel_force=H_accel_force/scale;
    
                HM_accel=abs(H_accel_force);
                app.a_f_mag(i)=amp*HM_accel;
    
            end    

            rd21=amp*abs(h_disp_complex(2)-h_disp_complex(1));

            disp(' ');
            disp(' Response      dof 1   dof 2 ');   

            if(app.iu==1)
                out1=sprintf('    Disp (in)  %6.3g   %6.3g \n',app.d_f_mag(1),app.d_f_mag(2));
                out2=sprintf(' Vel (in/sec)  %6.3g   %6.3g \n',app.v_f_mag(1),app.v_f_mag(2));
                out3=sprintf('    Accel (G)  %6.3g   %6.3g \n',app.a_f_mag(1),app.a_f_mag(2));
            else
                out1=sprintf('       Disp (mm)  %6.3g   %6.3g \n',app.d_f_mag(1)*1000,app.d_f_mag(2)*1000);
                out2=sprintf('    Vel (cm/sec)  %6.3g   %6.3g \n',app.v_f_mag(1)*100,app.v_f_mag(2)*100);
                out3=sprintf('       Accel (G)  %6.3g   %6.3g \n',app.a_f_mag(1),app.a_f_mag(2));
            end
            
            fprintf(out1); 
            fprintf(out2);
            fprintf(out3);   

            ss=sprintf(' Response      dof 1   dof 2 \n\n  %s %s %s ',out1,out2,out3);


            disp(' ');
            disp(' Relative Displacement (dof 2 - dof 1) ');
            if(app.iu==1)
                out4=sprintf('    Disp (in)  %6.3g \n',rd21);
            else
                out4=sprintf('    Disp (mm)  %6.3g \n',rd21*1000);    
            end

            fprintf(out4);
            disp(' ');

            fprintf(out4);
            ss=sprintf('%s \n Relative Displacement (dof 2 - dof 1) \n\n  %s ',ss,out4);

            app.ResultsReadOnlyTextArea.Value=ss;

        end

        % Button pushed function: CalculateButton_Trans
        function CalculateButton_TransPushed(app, event)
            
            check(app);
            
            if(app.zflag==1)
                return;
            end
            
            fig_num=1;
             
            fmin=app.StartFreqHzEditField.Value;
            fmax=app.EndFreqHzEditField.Value;
             
            if(fmin<=0.01)
                fmin=0.01;
            end    

            tpi=2*pi;
   
            N=48;
    
            [freq,omega,nf]=frequencies_octave_spaced(fmin,fmax,N);
    
            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
           
            num_modes=length(fnv);

            omn=tpi*fnv;
            omn2=omn.*omn;

            omega2=zeros(nf,1);

            for i=1:nf
                omega2(i)=(omega(i))^2;
            end

            nrb=0; % leave as zero

            num_columns=num_modes;
            
            if(contains(app.ResponsedofListBox.Value,'1'))
                i=1;
            else
                i=2;
            end            
            if(contains(app.ForcedofListBox.Value,'1'))
                k=1;
            else
                k=2;
            end

            freq=fix_size(freq);

            iam=1;
                [H_disp_force]=...
                transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_disp=abs(H_disp_force);
                HP_disp=atan2(imag(H_disp_force),real(H_disp_force))*180/pi;
                H_disp_force(1:3,:)
                ymax=max(HM_disp);
                ymin=min(HM_disp);
                [fig_num]=...
                transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_disp,HP_disp);
                app.d_f_mag=[freq HM_disp];
                app.d_f_mag_phase=[freq HM_disp HP_disp];
                app.d_f_mag_complex=[freq H_disp_force];
      
               iam=2;
                   [H_velox_force]=...
                   transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                   HM_velox=abs(H_velox_force);
                   HP_velox=atan2(imag(H_velox_force),real(H_velox_force))*180/pi;
                   ymax=max(HM_velox);
                   ymin=min(HM_velox);    
                   [fig_num]=...
                   transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_velox,HP_velox);   
                   app.v_f_mag=[freq HM_velox];
                   app.v_f_mag_phase=[freq HM_velox HP_velox];
                   app.v_f_mag_complex=[freq H_velox_force];

               iam=3;
                   [H_accel_force]=...
                   transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);

                   if(app.iu==1)
                       scale=386;
                   else
                       scale=9.81;
                   end
    
                   H_accel_force= H_accel_force/scale;

                   HM_accel=abs(H_accel_force); 
                   HP_accel=atan2(imag(H_accel_force),real(H_accel_force))*180/pi;
                   ymax=max(HM_accel);
                   ymin=min(HM_accel);    
                   [fig_num]=...
                   transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_accel,HP_accel);
                   app.a_f_mag=[freq HM_accel];
                   app.a_f_mag_phase=[freq HM_accel HP_accel];
                   app.a_f_mag_complex=[freq H_accel_force];

               %%%%%%
               
        end

        % Button pushed function: SaveButton_Trans
        function SaveButton_TransPushed(app, event)
            m=strtrim(app.ExportTrans.Value);
            n=strtrim(app.ExportAmplitude.Value);
            output_name=strtrim(app.OutputArrayNameEditFieldTrans.Value);
                
            if(strcmp(m,'Freq & Magnitude'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag;          
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag;   
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag; 
                end                
            end
            if(strcmp(m,'Freq & Magnitude & Phase'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag_phase;        
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag_phase;    
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag_phase; 
                end                 
            end
            if(strcmp(m,'Freq & Complex Magnitude'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag_complex; 
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag_complex; 
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag_complex; 
                end                 
            end            
            
            assignin('base',output_name, data); 
            msgbox('Data saved');
        end

        % Button pushed function: SaveHSButton
        function SaveHSButtonPushed(app, event)
            Items = strtrim(app.OutputTimeHistoryArrayListBox.Items);
            im = strtrim(app.OutputTimeHistoryArrayListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.displacement;
            end
            if(n==2)
                data=app.rel_disp;                
            end
            if(n==3)
                data=app.velocity;                   
            end
            if(n==4)
                data=app.acceleration;                
            end
            if(n==5)
                d=app.acceleration;
                data=[d(:,1) d(:,2)];
            end    
            if(n==6)
                d=app.acceleration; 
                data=[d(:,1) d(:,3)];           
            end           
            if(n==7)
                data=app.FD1;                
            end
            if(n==8)
                data=app.FD2;                
            end  
            if(n==9)
                data=[app.FD1 app.FD2(:,3)];                
            end
            output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');
        end

        % Button pushed function: CalculateButton_HalfSine
        function CalculateButton_HalfSinePushed(app, event)
            check(app);
            
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');
            
            if(strcmp(app.AppliedForcedofListBox_HS.Value,'1'))
                p=1;
            else
                p=2;
            end
      
            F=app.AppliedHSForceEditField.Value;
            T=app.PulseDurationsecEditField.Value;

            dur=app.TotalDurationsecEditField.Value;

            sr1=30/T;
            sr2=30*max(app.fn);
            sr=max([sr1 sr2]);

            dt=1/sr;

            nt=round(dur/dt);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            damp=app.dm;
            
            B=zeros(2,1);
            alpha=zeros(2,1);
          
            B(p,1)=F;
            alpha(p,1)=T;
            alpha(p,1)=pi/alpha(p,1);
            
            x_init=zeros(2,1);
            v_init=zeros(2,1);

            nd=app.MST*app.M*x_init;
            nv=app.MST*app.M*v_init;

            omegad=zeros(2,1);
            omegan2=zeros(2,1);
            omegad2=zeros(2,1);            
            domegan=zeros(2,1);
            AA=zeros(2,1);
            AAT=zeros(2,1);

            U1=zeros(2,2);
            U2=zeros(2,2);

            V1=zeros(2,2);
            V2=zeros(2,2);
            den=zeros(2,2);
            C=zeros(2,2);
            W=zeros(2,2);

            alpha2=alpha.^2;

            for j=1:2

                omegad(j)=app.omegan(j)*sqrt(1-(damp(j))^2);
                domegan(j)=damp(j)*app.omegan(j);
                omegan2(j)=app.omegan(j)^2;
                omegad2(j)=omegad(j)^2;
                AA(j)=(nv(j)+domegan(j)*nd(j))/omegad(j);     

                for k=1:2

                    U1(j,k)= 2*domegan(j)*alpha(k);
                    U2(j,k)= 2*domegan(j)*omegad(j);

                    V1(j,k)= (alpha2(k)-omegan2(j));
                    V2(j,k)= (alpha2(k)-omegan2(j)*(1-2*damp(j)^2));

                    den(j,k)=(alpha2(k)-omegan2(j))^2+(2*domegan(j)*alpha(k))^2;
                    C(j,k)=app.MST(j,k)*B(k)/den(j,k);

                    W(j,k)=alpha(k)/omegad(j);
      
                end
            end

            n=zeros(2,nt);
            nvelox=zeros(2,1);
            naccel=zeros(2,1);

            x=zeros(nt,2);
            v=zeros(nt,2);
            accel=zeros(nt,2); 

            ndT=zeros(2,1);
            nvT=zeros(2,1);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Solve for initial conditions at end of pulse
            %
                 for j=1:2  % nodal dof
            %
                    omdt=omegad(j)*T;
                    cos_odt=cos(omdt);
                    sin_odt=sin(omdt);
            %      
                     n1=zeros(2,1);
                     n2=zeros(2,1);
                    nv1=zeros(2,1);
                    nv2=zeros(2,1);
                    na1=zeros(2,1);
                    na2=zeros(2,1);
            %
                      ee=exp(-domegan(j)*T);
                     dee=-domegan(j)*ee;
                    ddee=(domegan(j))^2*ee;
            %
                    for k=1:2  % forces
            %
                        alphat=alpha(k)*T; 
            %          
                        n1(k)=      (U1(j,k)*cos(alphat) + V1(j,k)*sin(alphat));
                        n2(k)=   ee*(U2(j,k)*cos_odt + V2(j,k)*sin_odt);
            %
                        n1(k)=n1(k)*C(j,k);
                        n2(k)=n2(k)*W(j,k)*C(j,k);
            %
                        nv1(k)=    alpha(k)*(-U1(j,k)*sin(alphat) + V1(j,k)*cos(alphat));
                        nv2(k)=omegad(j)*ee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)...
                                       +dee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);                 
            %
                        nv1(k)=nv1(k)*C(j,k);
                        nv2(k)=nv2(k)*W(j,k)*C(j,k);            
            %
                        na1(k)= alpha2(k)*(-U1(j,k)*cos(alphat) - V1(j,k)*sin(alphat));
                        na2(k)=    omegad2(j)*ee*(-U2(j,k)*cos_odt - V2(j,k)*sin_odt)...
                                 +2*omegad(j)*dee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)... 
                                 +           ddee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);             
            %  
                        na1(k)=na1(k)*C(j,k);
                        na2(k)=na2(k)*W(j,k)*C(j,k); 
            %
                    end  % forces
            %      
                    n3= ee*(nd(j)*cos_odt + AA(j)*sin_odt);
            %
                    nv3=           dee*( nd(j)*cos_odt + AA(j)*sin_odt)+...
                            omegad(j)*ee*(-nd(j)*sin_odt + AA(j)*cos_odt);
            %           
                    ndT(j)= -sum(n1)+sum(n2) + n3;
                    nvT(j)=-sum(nv1)+sum(nv2)+nv3;
                 end  % nodal dof
            %
                 for j=1:2
                    AAT(j)=(nvT(j)+domegan(j)*ndT(j))/omegad(j); 
                 end         
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                t=(1:nt)*dt-dt;
                t=fix_size(t);
                force=zeros(nt,1);
            %
                for i=1:nt
            %
                    if(t(i)<=T)
                        force(i)=B(p)*sin(alpha(p)*t(i));     
            %
                        for j=1:2  % nodal dof
            %
                            omdt=omegad(j)*t(i);
                            cos_odt=cos(omdt);
                            sin_odt=sin(omdt);
            %
                            n(j,i)=0;
                            nvelox(j)=0;
                            naccel(j)=0.;
            %      
                            n1=zeros(2,1);
                            n2=zeros(2,1);
                            nv1=zeros(2,1);
                            nv2=zeros(2,1);
                            na1=zeros(2,1);
                            na2=zeros(2,1);
            %
                            ee=exp(-domegan(j)*t(i));
                            dee=-domegan(j)*ee;
                            ddee=(domegan(j))^2*ee;
            %
                            for k=1:2  % forces
            %
                                alphat=alpha(k)*t(i); 
            %          
                                n1(k)=      (U1(j,k)*cos(alphat) + V1(j,k)*sin(alphat));
                                n2(k)=   ee*(U2(j,k)*cos_odt + V2(j,k)*sin_odt);
            %
                                n1(k)=n1(k)*C(j,k);
                                n2(k)=n2(k)*W(j,k)*C(j,k);
            %
                                nv1(k)=    alpha(k)*(-U1(j,k)*sin(alphat) + V1(j,k)*cos(alphat));
                                nv2(k)=omegad(j)*ee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)...
                                        +dee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);                 
            %
                                nv1(k)=nv1(k)*C(j,k);
                                nv2(k)=nv2(k)*W(j,k)*C(j,k);            
            %
                                na1(k)= alpha2(k)*(-U1(j,k)*cos(alphat) - V1(j,k)*sin(alphat));
                                na2(k)=    omegad2(j)*ee*(-U2(j,k)*cos_odt - V2(j,k)*sin_odt)...
                                        +2*omegad(j)*dee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)... 
                                        +           ddee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);             
            %  
                                na1(k)=na1(k)*C(j,k);
                                na2(k)=na2(k)*W(j,k)*C(j,k); 
            %
                            end
            %      
                            n3= ee*(nd(j)*cos_odt + AA(j)*sin_odt);
            %
                            nv3= dee*( nd(j)*cos_odt + AA(j)*sin_odt)+...
                                 omegad(j)*ee*(-nd(j)*sin_odt + AA(j)*cos_odt);
            %
                            na3=-2*domegan(j)*nv3-omegan2(j)*n3;
            %           
                            n(j,i)= -sum(n1)+sum(n2) + n3;
                            nvelox(j)=-sum(nv1)+sum(nv2)+nv3;
                            naccel(j)=-sum(na1)+sum(na2)+na3;
                        end
            %     
                    else % free 
            %
                        for j=1:2
                            tau=t(i)-T;
            % 
                            omdt=omegad(j)*tau;
                            cos_odt=cos(omdt);
                            sin_odt=sin(omdt);

                            n(j,i)=0;
                            nvelox(j)=0;
                            naccel(j)=0.;
            %
                              ee=exp(-domegan(j)*tau);
                             dee=-domegan(j)*ee;
            %
                            n3= ee*(ndT(j)*cos_odt + AAT(j)*sin_odt);
            %
                            nv3= dee*( ndT(j)*cos_odt + AAT(j)*sin_odt)+...
                                    omegad(j)*ee*(-ndT(j)*sin_odt + AAT(j)*cos_odt);
            %
                            na3=-2*domegan(j)*nv3-omegan2(j)*n3;
            %
                            n(j,i)=  n3;
                            nvelox(j)= nv3;
                            naccel(j)= na3;
            %
                        end
                    end
            %   
                        x(i,:)=(app.ModeShapes*n(:,i))';
                        v(i,:)=(app.ModeShapes*nvelox)';
                    accel(i,:)=(app.ModeShapes*naccel)';   
                end
            %
                if(app.iu==1)
                    accel=accel/386.;
                end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(1);
            plot(t,force);
            title('Half-Sine Force Pulse')
            if(app.iu==1)
                ylabel('Force (lbf)');
            else
                ylabel('Force (N)');
            end
            xlabel('Time (sec)');
            grid on;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                out1=sprintf(' Peak Displacement \n\n');
            else
                out1=sprintf(' Peak Displacement (m) \n\n');
            end
            out2=sprintf('dof 1  %8.4g   %8.4g  \n',max(x(:,1)),min(x(:,1)));
            out3=sprintf('dof 2  %8.4g   %8.4g  \n\n',max(x(:,2)),min(x(:,2)));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            rd=x(:,2)-x(:,1);
            
            if(app.iu==1)
                out4=sprintf(' Peak Relative Displacement (in) \n\n');
            else
                out4=sprintf(' Peak Relative Displacement (mm) \n\n');
            end
            out5=sprintf('dof 2 - dof 1  %8.4g   %8.4g  \n\n',max(rd),min(rd));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            if(app.iu==1)
                out6=sprintf(' Peak Velocity (in/sec) \n\n');
            else
                out6=sprintf(' Peak Velocity (m/sec) \n\n');
            end
            out7=sprintf('dof 1  %8.4g   %8.4g  \n',max(v(:,1)),min(v(:,1)));
            out8=sprintf('dof 2  %8.4g   %8.4g  \n\n',max(v(:,2)),min(v(:,2)));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     
            
            if(app.iu==1)
                out9=sprintf(' Peak Acceleration (G) \n\n');
            else
                out9=sprintf(' Peak Acceleration (m/sec^2) \n\n');
            end
            out10=sprintf('dof 1  %8.4g   %8.4g  \n',max(accel(:,1)),min(accel(:,1)));
            out11=sprintf('dof 2  %8.4g   %8.4g  \n',max(accel(:,2)),min(accel(:,2)));
            %
            fprintf(out1)
            fprintf(out2)
            fprintf(out3)
            fprintf(out4)
            fprintf(out5)
            fprintf(out6)
            fprintf(out7)
            fprintf(out8)
            fprintf(out9)
            fprintf(out10)
            fprintf(out11)            
            ss=sprintf('%s%s%s%s%s%s%s%s%s%s%s',out1,out2,out3,out4,out5,...
                                                out6,out7,out8,out9,out10,out11);

            app.ResultsReadOnlyTextArea.Value=ss;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            figure(2);
            plot(t,x(:,1),t,x(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Disp (inch)');
            else
                ylabel('Disp (m)');
            end
            title('Displacement');
            legend ('dof 1','dof 2');  
            grid on;
            %
            figure(3);
            clear rd;
            rd=x(:,2)-x(:,1);
            plot(t,rd);
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Rel Disp (inch)');
            else
                ylabel('Rel Disp (m)');
            end
            title('Relative Displacement (dof 2 - dof 1)');  
            grid on;
            %
            figure(4);
            plot(t,v(:,1),t,v(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Vel (in/sec)');
            else
                ylabel('Vel (m/sec)');
            end
            title('Velocity');
            legend ('dof 1','dof 2');  
            grid on;
            %
            figure(5);
            plot(t,accel(:,1),t,accel(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Accel (G)');
            else
                ylabel('Accel (m/sec^2)');
            end
            title('Acceleration');
            legend ('dof 1','dof 2'); 
            grid on;
            %
            app.displacement=[t,x(:,1),x(:,2)];
            app.velocity=[t,v(:,1),v(:,2)];
            app.acceleration=[t,accel(:,1),accel(:,2)];
            app.rel_disp=[t,rd];
            %
            app.FD1=[t  force   x(:,1)];
            app.FD2=[t  force   x(:,2)];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
        end

        % Value changed function: AppliedForcedofListBox_HS
        function AppliedForcedofListBox_HSValueChanged(app, event)
           
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: AppliedHSForceEditField
        function AppliedHSForceEditFieldValueChanged(app, event)
            
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: PulseDurationsecEditField
        function PulseDurationsecEditFieldValueChanged(app, event)
            
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Value changed function: TotalDurationsecEditField
        function TotalDurationsecEditFieldValueChanged(app, event)
      
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Button pushed function: SaveDiracButton
        function SaveDiracButtonPushed(app, event)
            output_name=app.OutputArrayNameEditField_Dirac.Value; 
          
            output_name1=sprintf('%s_1',output_name);
            output_name2=sprintf('%s_2',output_name);          

            n=strtrim(app.OutputTimeHistoryArrayListBox_Dirac.Value);

            nflag=0;

            if(strcmp(n,'Displacement 1 & 2'))
                data1=[app.displacement(:,1) app.displacement(:,2)];
                data2=[app.displacement(:,1) app.displacement(:,3)];
            end
            if(strcmp(n,'Relative Displacement 2-1'))
                data=app.rel_disp;   
                assignin('base',output_name, data);
                nflag=1;
            end
            if(strcmp(n,'Velocity 1 & 2'))
                data1=[app.velocity(:,1) app.velocity(:,2)];
                data2=[app.velocity(:,1) app.velocity(:,3)];               
            end
            if(strcmp(n,'Acceleration 1 & 2'))
                data1=[app.acceleration(:,1) app.acceleration(:,2)];
                data2=[app.acceleration(:,1) app.acceleration(:,3)];                
            end

            if(nflag==0)
                assignin('base',output_name1, data1);
                assignin('base',output_name2, data2);
            end
     
            
            msgbox('Data saved');   
        end

        % Button pushed function: CalculateButton_Dirac
        function CalculateButton_DiracPushed(app, event)
                        
            check(app);
      
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            

            fig_num=1;

            damp=app.dm;
  
            dur=app.TotalDurationsecEditField_Dirac.Value;
            
            sr=app.SampleRateHzEditField_Dirac.Value;   
            
            dt=1/sr;
            
            nt=round(dur/dt);
            
            Q=app.ModeShapes;
            
            nd=zeros(2,nt);
            d=zeros(2,nt);
            
            nv=zeros(2,nt);
            v=zeros(2,nt);
            
            na=zeros(2,nt);
            acc=zeros(2,nt);
            
            t=zeros(nt,1);
            
            Imp=app.TotalImpulselbfsecEditField_Dirac.Value;
            
            %%%
               
            omegad=zeros(2,1);
            domegan=zeros(2,1);
            
            for i=1:2
                omegad(i)=app.omegan(i)*sqrt(1-damp(i)^2);
               domegan(i)=damp(i)*app.omegan(i);
            end
            
            if(max(abs(damp))>1)
               warndlg('Damping Error'); 
               return; 
            end
            
            for i=1:nt
                
               t(i)=(i-1)*dt;   
                  
               a1=-domegan(1)*t(i);
               b1=  omegad(1)*t(i);
               
               a2=-domegan(2)*t(i);
               b2=  omegad(2)*t(i);
               
               sinb1=sin(b1);   
               sinb2=sin(b2);   
               
               cosb1=cos(b1);
               cosb2=cos(b2);   
                 
               n1= Q(2,1)*exp(a1)*sinb1/omegad(1); 
               n2= Q(2,2)*exp(a2)*sinb2/omegad(2);    
            
               nd(1,i)=Imp*n1;
               nd(2,i)=Imp*n2;
               d(:,i)=Q*nd(:,i);
               
            %%%   
            
               n1= Q(2,1)*( -domegan(1)*sinb1+omegad(1)*cosb1 )*exp(a1)/omegad(1); 
               n2= Q(2,2)*( -domegan(2)*sinb2+omegad(2)*cosb2 )*exp(a2)/omegad(2);   
            
               nv(1,i)=Imp*n1;
               nv(2,i)=Imp*n2;
               v(:,i)=Q*nv(:,i);
            
            %%%
            
               n11=  -domegan(1)*( -domegan(1)*sinb1+omegad(1)*cosb1 );
               n12=    omegad(1)*( -domegan(1)*cosb1-omegad(1)*sinb1 );
            
               n21=  -domegan(2)*( -domegan(2)*sinb2+omegad(2)*cosb2 );
               n22=    omegad(2)*( -domegan(2)*cosb2-omegad(2)*sinb2 );  
              
               na(1,i)=Imp*Q(2,1)*(n11+n12)*exp(a1)/omegad(1);
               na(2,i)=Imp*Q(2,2)*(n21+n22)*exp(a2)/omegad(2);
               
               acc(:,i)=Q*na(:,i);   
               
            %%%
            end
            %
            
            data1=[t nd(1,:)'];
            data2=[t nd(2,:)'];
            
            t_string1='Modal Displacements';
            t_string2=' ';
            
            xlabel2='Time (sec)';
            
            ylabel1=' mode 1 ';
            ylabel2=' mode 2 ';
            
            [fig_num]=subplots_two_linlin_two_titles_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            
            dd=d;
            if(app.iu==2)
                dd=dd*1000;
            end
            
            plot(t,dd(1,:),t,dd(2,:));

            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Displacement');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Displacement (in) ');
                out1=sprintf(' Peak Displacement (in) \n\n');
            else
                ylabel(' Displacement (mm) ');
                out1=sprintf(' Peak Displacement (mm) \n');
            end
            
            out2=sprintf('dof 1  %8.4g  %8.4g  \n',max(dd(1,:)),min(dd(1,:)));
            out3=sprintf('dof 2  %8.4g  %8.4g  \n',max(dd(2,:)),min(dd(2,:)));


            fprintf(out1);
            fprintf(out2);
            fprintf(out3);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            dd=fix_size(dd);
            
            rd=zeros(nt,1);
            
            for i=1:nt
                rd(i)=dd(i,2)-dd(i,1);
            end
                
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,rd);
            xlabel('Time (sec)');
            title('Relative Displacement (dof 2 - dof 1)');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Disp (in) ');
                out4=sprintf('\n Peak Relative Displacement (in) \n');
            else
                ylabel(' Disp (mm) ');
                out4=sprintf('\n Peak Relative Displacement (mm) \n');
            end
            
            out5=sprintf('dof 1  %8.4g  %8.4g  \n',max(rd),min(rd));

            fprintf(out4);
            fprintf(out5);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,v(1,:),t,v(2,:));
            legend ('dof 1','dof 2');   
            xlabel('Time (sec)');
            title('Velocity');
            grid on;
            %
            
            disp(' ');
            if(app.iu==1)
                ylabel(' Velocity (in/sec) ');
                out6=sprintf('\n Peak Velocity (in/sec) \n\n');
            else
                ylabel(' Velocity (mm/sec) ');
                out6=sprintf('\n Peak Velocity (mm/sec) \n\n');
            end
             
            out7=sprintf('dof 1  %8.4g  %8.4g  \n',max(v(1,:)),min(v(1,:)));
            out8=sprintf('dof 2  %8.4g  %8.4g  \n',max(v(2,:)),min(v(2,:)));

            fprintf(out6);
            fprintf(out7);
            fprintf(out8);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            % fig_num=fig_num+1;
            if(app.iu==1)
                acc=acc/386;
            else
                acc=acc/9.81;
            end
            plot(t,acc(1,:),t,acc(2,:));
            legend ('dof 1','dof 2');   
            xlabel('Time (sec)');
            title('Acceleration');
            ylabel(' Accel (G) ');
            grid on;
            
            out9=sprintf('\n Peak Acceleration (G) \n\n');
                    
            out10=sprintf('dof 1  %8.4g  %8.4g  \n',max(acc(1,:)),min(acc(1,:)));
            out11=sprintf('dof 2  %8.4g  %8.4g  \n',max(acc(2,:)),min(acc(2,:)));

            fprintf(out9);
            fprintf(out10);
            fprintf(out11);
            
            v=fix_size(v);
            acc=fix_size(acc);

            app.displacement=[t, dd(:,1), dd(:,2)];
            app.rel_disp=[t rd];
         
            app.velocity=[t v(:,1) v(:,2)];
            app.acceleration=[t acc(:,1) acc(:,2)];

            ss=sprintf('%s%s%s%s%s%s%s%s%s%s%s',out1,out2,out3,out4,out5,...
                                      out6,out7,out8,out9,out10,out11);

            app.ResultsReadOnlyTextArea.Value=ss;

        end

        % Button pushed function: SaveDiracButton_3
        function SaveDiracButton_3Pushed(app, event)
            cpsd_H1_H2_a;
        end

        % Callback function
        function SaveDiracButton_2Pushed(app, event)
            cpsd_H1_H2_a;
        end

        % Button pushed function: SaveArbitraryButton
        function SaveArbitraryButtonPushed(app, event)
           
            Items = strtrim(app.OutputTimeHistoryArrayListBox_Arbitrary.Items);
            im = strtrim(app.OutputTimeHistoryArrayListBox_Arbitrary.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                data=app.displacement;
            end
            if(n==2)
                data=app.rel_disp;                
            end
            if(n==3)
                data=app.velocity;                   
            end
            if(n==4)
                data=app.acceleration;                
            end
            if(n==5)
                d=app.acceleration;
                data=[d(:,1) d(:,2)];
            end    
            if(n==6)
                d=app.acceleration; 
                data=[d(:,1) d(:,3)];           
            end           
            if(n==7)
                data=app.FD1;                
            end
            if(n==8)
                data=app.FD2;                
            end  
            if(n==9)
                data=[app.FD1 app.FD2(:,3)];                
            end

            output_name=app.OutputArrayNameEditField_arb.Value; 

            assignin('base',output_name, data); 
            msgbox('Data saved');            


        end

        % Button pushed function: SaveDiracButton_4
        function SaveDiracButton_4Pushed(app, event)
            cpsd_H1_H2_a;
        end

        % Button pushed function: UpdateListButton_arb_force
        function UpdateListButton_arb_forcePushed(app, event)
            update_arb_force(app);
        end

        % Button pushed function: CalculateButton_Arbitrary
        function CalculateButton_ArbitraryPushed(app, event)
            check(app);
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            
            

            fig_num=1;
            
            
                 try
                     FS=app.SelectInputArrayListBox_arb_force.Value;
                     THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end

            %%%          
             
            n=strtrim(app.AppliedForcedofListBox_Arbitrary.Value);    

            t=THM(:,1);

            nt=length(t);
            f1=zeros(nt,1);
            f2=zeros(nt,1);

            if(strcmp(n,'1'))
                f1=THM(:,2);
            end
            if(strcmp(n,'2'))
                f2=THM(:,2);
            end
            if(strcmp(n,'1 & 2'))
                f1=THM(:,2);
                f2=THM(:,3);
            end

            F=[f1 f2];

            %
            tmx=t(end);
            tmi=t(1);

            dur=tmx-tmi;

            dt=dur/(nt-1);
            sr=1/dt;
            %
            if(sr<10*max(app.fn))
                disp(' ');
                warndlg(' Warning: insufficient sample rate. ');
            end

            ndof=2;
            sys_dof=ndof;
            
            dampv=app.dm;

            [x,v,a,~,~,~]=ramp_invariant_force(app.ModeShapes,F,ndof,sys_dof,app.omegan,dampv,dt);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            t_string1='dof 1';
            t_string2='dof 2';

            %       
            figure(fig_num);
            fig_num=fig_num+1;

            dd=x;
            if(app.iu==2)
                dd=dd*1000;
            end

            dd=fix_size(dd);
            v=fix_size(v);
            a=fix_size(a);

            plot(t,dd(:,1),t,dd(:,2));

            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Displacement');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Displacement(in) ');
                out1=sprintf(' Peak Displacement (in) \n\n');
                ylabel1='Disp(in)';    
            else
                ylabel(' Displacement(mm) ');
                out1=sprintf(' Peak Displacement (mm) \n\n');
                ylabel1='Disp(mm)';      
            end
            
            out2=sprintf('dof 1  %8.4g  %8.4g  \n',max(dd(:,1)),min(dd(:,1)));
            out3=sprintf('dof 2  %8.4g  %8.4g  \n',max(dd(:,2)),min(dd(:,2)));

            nbars=31;
            
            data1=[t dd(:,1)];
            data2=[t dd(:,2)];
            t_string1='Displacement Mass 1';
            t_string2='Displacement Mass 2';
            ylabel2=ylabel1;
            xlabel2='Time(sec)';
            [fig_num]=plot_two_time_histories_histograms(fig_num,xlabel2,...
                                ylabel1,ylabel2,data1,data2,t_string1,t_string2,nbars);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t dd(:,1)];
            data2=[ t dd(:,2)];
            
            xlabel2='Time(sec)';
            ylabel2=ylabel1;
            
            [fig_num]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            app.displacement=[t dd(:,1) dd(:,2)];
            app.FD1=[THM dd(:,1)];
            app.FD2=[THM dd(:,2)];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            rd=zeros(nt,1);
            
            for i=1:nt
                rd(i)=dd(i,1)-dd(i,2);
            end
                
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,rd);
            xlabel('Time(sec)');
            title('Relative Displacement (dof 2 - dof 1)');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Disp(in) ');
                out4=sprintf('\n Peak Relative Displacement (in) \n');
            else
                ylabel(' Disp(mm) ');
                out4=sprintf('\n Peak Relative Displacement (mm) \n');
            end
            
            out5=sprintf('dof 2 - dof 1  %8.4g  %8.4g  \n',max(rd),min(rd));
           
            app.rel_disp=[t rd];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,v(:,1),t,v(:,2));
            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Velocity');
            
            grid on;
            
            disp(' ');
            if(app.iu==1)
                ylabel(' Velocity(in/sec) ');
                out6=sprintf('\n Peak Velocity (in/sec) \n\n');
                ylabel1='Vel(in/sec)';  
            else
                ylabel(' Velocity(mm/sec) ');
                out6=sprintf('\n Peak Velocity (mm/sec) \n\n');
                ylabel1='Vel(mm/sec)';  
            end
 
            out7=sprintf('dof 1  %8.4g  %8.4g  \n',max(v(:,1)),min(v(:,1)));
            out8=sprintf('dof 2  %8.4g  %8.4g  \n',max(v(:,2)),min(v(:,2)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t v(:,1)];
            data2=[ t v(:,2)];

            t_string1='Velocity Mass 1';
            t_string2='Velocity Mass 2';
            
            xlabel2='Time(sec)';
            ylabel2=ylabel1;
            
            [fig_num]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);

            app.velocity=[t v(:,1) v(:,2)];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            acc=a;
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            if(app.iu==1)
                acc=acc/386;
            else
                acc=acc/9.81;
            end
            plot(t,acc(:,1),t,acc(:,2));
            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Acceleration');
            ylabel(' Accel(G) ');
            grid on;
            disp(' ');
            out9=sprintf('\n Peak Acceleration (G) \n\n');
        
            out10=sprintf('dof 1  %8.4g  %8.4g  \n',max(acc(:,1)),min(acc(:,1)));
            out11=sprintf('dof 2  %8.4g  %8.4g  \n',max(acc(:,2)),min(acc(:,2)));
            
            app.acceleration=[t acc(:,1) acc(:,2)];
            
            disp(' ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t acc(:,1)];
            data2=[ t acc(:,2)];
            
            xlabel2='Time(sec)';
            ylabel1='Accel(G)';
            ylabel2=ylabel1;

            t_string1='Acceleration Mass 1';
            t_string2='Acceleration Mass 2';
            
            [~]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fprintf(out1);
            fprintf(out2);
            fprintf(out3);
            fprintf(out4);
            fprintf(out5);
            fprintf(out6);  


            fprintf(out7);
            fprintf(out8);
            fprintf(out9);
            fprintf(out10);
            fprintf(out11);         

            ss=sprintf('%s%s%s%s%s%s%s%s%s%s%s',out1,out2,out3,out4,...
                                    out5,out6,out7,out8,out9,out10,out11);
            app.ResultsReadOnlyTextArea.Value=ss;

        end

        % Value changed function: AppliedForcedofListBox_Arbitrary
        function AppliedForcedofListBox_ArbitraryValueChanged(app, event)
          
            app.ResultsReadOnlyTextArea.Value='';
        end

        % Button pushed function: SaveDiracButton_5
        function SaveDiracButton_5Pushed(app, event)
            cpsd_H1_H2_a;
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)
            
            n=strtrim(app.OutputArrayListBox_psd.Value);    
 
            try
                
                if(strcmp(n,'Displacement 1 & 2'))
                    data=app.displacement;
                end
                if(strcmp(n,'Relative Displacement 2-1'))
                    data=app.rel_disp;                
                end
                if(strcmp(n,'Velocity 1 & 2'))
                    data=app.velocity;                   
                end
                if(strcmp(n,'Acceleration 1 & 2'))
                    data=app.acceleration;                
                end
                if(strcmp(n,'Force & Displacement 1'))
                    data=app.FD1;                
                end
                if(strcmp(n,'Force & Displacement 2'))
                    data=app.FD2;                
                end  
                if(strcmp(n,'Force & Displacement 1 & 2'))
                    data=[app.FD1 app.FD2(:,3)];         
                end
            
                output_name=app.OutputArrayNameEditField_PSD.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end
        end

        % Button pushed function: UpdateListButton_arb_force_2
        function UpdateListButton_arb_force_2Pushed(app, event)
            Update_psd(app);
        end

        % Button pushed function: CalculateButton_PSD
        function CalculateButton_PSDPushed(app, event)
                      
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            

            fig_num=1;

            
            try
                FS=app.SelectInputPSDArrayListBox.Value;
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            %
            THM=fix_size(THM);
            %
            sz=size(THM);
            n=sz(1);
            %
            fstart=THM(1,1);
            fend=THM(n,1);
            %
            if(fstart<=0)
                warndlg('Error: starting frequency must be >0 ');
                return;
            end
 
            tpi=2*pi;

            num=length(THM(:,1));
            
            fmin=THM(1,1);
            fmax=THM(num,1);
            
            N=96;
            %
            [freq,omega,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            [fpsd]=interp_psd_oct(THM(:,1),THM(:,2),freq);

            freq=fix_size(freq);
            fpsd=fix_size(fpsd);
            
            %%%%%%%%%%%%%%%%
            %
            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
            nf=length(freq);
            num_modes=length(fnv);
            %
            omn=tpi*fnv;
            omn2=omn.*omn;
            %
            omega2=zeros(nf,1);
            %
            for i=1:nf
                omega2(i)=(omega(i))^2;
            end
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n  %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end


            %%%%%%
            
            freq=fix_size(freq);
            numf=length(freq);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              
            num_columns=2;
            
            H_disp_force_complex=zeros(numf,2,2);
            H_velox_force_complex=zeros(numf,2,2);
            H_accel_force_complex=zeros(numf,2,2);
            
            for i=1:2
                
                for k=1:2
            %    
                    iam=1;
                    [H_disp_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
            
                    if(app.iu==2)
                        H_disp_force=H_disp_force*1000;
                    end   
                
                    H_disp_force_complex(:,i,k)=H_disp_force;
            %    
                    iam=2;
                    [H_velox_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                    H_velox_force_complex(:,i,k)=H_velox_force;
               
                    iam=3;
                    [H_accel_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
            
                    if(app.iu==1)
                        scale=386;
                    else
                        scale=9.81;
                    end
                
                    H_accel_force_complex(:,i,k)=H_accel_force/scale;
                
                end
                
            end    

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_psd=zeros(numf,2);   
            vel_psd=zeros(numf,2);    
            disp_psd=zeros(numf,2);    
            disp_psd_complex=zeros(numf,2); 
            rel_disp_psd=zeros(numf,1);
            
            Items = strtrim(app.AppliedForcedofListBox.Items);
            im = strtrim(app.AppliedForcedofListBox.Value);
            kd = find(strcmp(Items, im));

            if(kd==1)
                    S=[1 0; 0 0];
            end
            if(kd==2)
                    S=[0 0; 0 1];
            end    
            if(kd==3)
                    S=[1 1; 1 1];
            end
            if(kd==4)
                    S=[1 0; 0 1];
            end     

            SS=S;
            
            for i=1:numf
                  
                S=SS*fpsd(i);
            
            %    
                H=[H_accel_force_complex(i,1,1)  H_accel_force_complex(i,1,2); H_accel_force_complex(i,2,1)  H_accel_force_complex(i,2,2) ];
                HC=conj(H);
                
                a=H*S*HC';

            %
                H=[H_velox_force_complex(i,1,1)  H_velox_force_complex(i,1,2); H_velox_force_complex(i,2,1)  H_velox_force_complex(i,2,2) ];
                HC=conj(H);
                v=H*S*HC';
            %
                H=[H_disp_force_complex(i,1,1)  H_disp_force_complex(i,1,2); H_disp_force_complex(i,2,1)  H_disp_force_complex(i,2,2) ];
                HC=conj(H);
                d=H*S*HC';
            %
                accel_psd(i,:)=abs(diag(a));
                vel_psd(i,:)=abs(diag(v));
                disp_psd(i,:)=abs(diag(d));
            
                disp_psd_complex(i,:)=diag(d);
                
            end
            
            dr=zeros(2,1);
            vr=zeros(2,1);
            ar=zeros(2,1);
             
            for j=1:2
                
                for i=1:numf
                     
                     if(j==1)
                        rel_disp_psd(i)=disp_psd_complex(i,2)-disp_psd_complex(i,1);      
                     end
                end
                
                [~,drms] = calculate_PSD_slopes_no(freq,disp_psd(:,j));
                [~,vrms] = calculate_PSD_slopes_no(freq,vel_psd(:,j));
                [~,arms] = calculate_PSD_slopes_no(freq,accel_psd(:,j));
                
                dr(j)=drms;
                vr(j)=vrms;
                ar(j)=arms;
                
            end
            
            rel_disp_psd=abs(rel_disp_psd);
            
            [~,rdr] = calculate_PSD_slopes_no(freq,rel_disp_psd);
            
            %%%%%%%%%%%%%%%%
            
            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                y_label_d='Disp (in^2/Hz)';  
                y_label_v='Vel ((in/sec)^2/Hz)'; 
                
                t_string_d='Displacement PSD';
                t_string_v='Velocity PSD';
                
                t_string_rd=sprintf(' Relative Displacement PSD %6.3g inch RMS',rdr);    
                
            else
                y_label_d='Disp (mm^2/Hz)'; 
                y_label_v='Vel ((m/sec)^2/Hz)';  
                
                t_string_d='Displacement PSD';
                t_string_v='Velocity PSD';   
                
                t_string_rd=sprintf(' Relative Displacement PSD %6.3g mm RMS',rdr);      
            end
            
            y_label_a='Accel (G^2/Hz)';
            t_string_a='Acceleration PSD';
            
            freq=fix_size(freq);
            
            rel_disp_psd=[freq rel_disp_psd];
                disp_psd=[freq disp_psd];
                 vel_psd=[freq vel_psd];
               accel_psd=[freq accel_psd];

            md=6;
            [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,y_label_d,t_string_rd,rel_disp_psd,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp=[disp_psd(:,1) disp_psd(:,2) ];
            qqq=[disp_psd(:,1) disp_psd(:,3) ];
            
            if(app.iu==1)
                leg_a=sprintf('dof 1 %6.3g in RMS',dr(1));
                leg_b=sprintf('dof 2 %6.3g in RMS',dr(2));
            else
                leg_a=sprintf('dof 1 %6.3g mm RMS',dr(1));
                leg_b=sprintf('dof 2 %6.3g mm RMS',dr(2));    
            end
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_d,t_string_d,ppp,qqq,leg_a,leg_b);
            
            ppp=[vel_psd(:,1) vel_psd(:,2) ];
            qqq=[vel_psd(:,1) vel_psd(:,3) ];
            
            if(app.iu==1)
                leg_a=sprintf('dof 1 %6.3g in/sec RMS',vr(1));
                leg_b=sprintf('dof 2 %6.3g in/sec RMS',vr(2));
            else
                leg_a=sprintf('dof 1 %6.3g mm/sec RMS',vr(1));
                leg_b=sprintf('dof 2 %6.3g mm/sec RMS',vr(2));   
            end
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_v,t_string_v,ppp,qqq,leg_a,leg_b);     
                           
            ppp=[accel_psd(:,1) accel_psd(:,2) ];
            qqq=[accel_psd(:,1) accel_psd(:,3) ];
            
            leg_a=sprintf('dof 1 %6.3g GRMS',ar(1));
            leg_b=sprintf('dof 2 %6.3g GRMS',ar(2));
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_a,t_string_a,ppp,qqq,leg_a,leg_b);     
                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.acceleration=accel_psd;
            app.velocity=vel_psd;    
            app.displacement=disp_psd; 
            app.rel_disp=rel_disp_psd;
                   
            app.FD1=[ freq fpsd disp_psd(:,2)];
            app.FD2=[ freq fpsd disp_psd(:,3)];
            
        end

        % Callback function
        function SettoEquivalentUnitForceButtonPushed(app, event)
            sr=app.SampleRateHzEditField_Dirac.Value;
            app.TotalImpulselbfsecEditField_Dirac.Value=1/sr;
        end

        % Button pushed function: GoToModalAnalysisButton
        function GoToModalAnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.model=1;
            ModelChange(app);
            app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            app.model=2;
            ModelChange(app);
            app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            app.model=3;
            ModelChange(app);
            app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.twodofforceUIFigure = uifigure('Visible', 'off');
            app.twodofforceUIFigure.Name = 'two dof force';
            app.two_dof_force_aver12byTomIrvineLabel = uilabel(app.twodofforceUIFigure);
            app.two_dof_force_aver12byTomIrvineLabel.Text = 'two_dof_force_a   ver 1.2  by Tom Irvine';
            app.ReturnButton = uibutton(app.twodofforceUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.twodofforceUIFigure);
            app.SelectSystemTab = uitab(app.TabGroup);
            app.SelectSystemTab.Title = 'Select System';
            app.UnitsListBoxLabel = uilabel(app.SelectSystemTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SelectSystemTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.Image = uiimage(app.SelectSystemTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'two_dof_force_1.jpg');
            app.Image_2 = uiimage(app.SelectSystemTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'two_dof_force_2.jpg');
            app.Image_3 = uiimage(app.SelectSystemTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'two_dof_force_3.jpg');
            app.GoToModalAnalysisButton = uibutton(app.SelectSystemTab, 'push');
            app.GoToModalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GoToModalAnalysisButtonPushed, true);
            app.GoToModalAnalysisButton.Text = 'Go To Modal Analysis';
            app.SelectButton = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SelectButton_3 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.Image_modal = uiimage(app.ModalAnalysisTab);
            app.TabGroup2 = uitabgroup(app.ModalAnalysisTab);
            app.MassStiffnessTab = uitab(app.TabGroup2);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.EnterValuesLabel = uilabel(app.MassStiffnessTab);
            app.EnterValuesLabel.Text = 'Enter Values';
            app.UITable = uitable(app.MassStiffnessTab);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.ModalAnalysisTab_2 = uitab(app.TabGroup2);
            app.ModalAnalysisTab_2.Title = 'Modal Analysis';
            app.CalculateButton = uibutton(app.ModalAnalysisTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.ModalAnalysisTab_2);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.ModalAnalysisTab_2);
            app.NaturalFrequenciesTextArea.Editable = 'off';
            app.MassMatrixTextAreaLabel = uilabel(app.ModalAnalysisTab_2);
            app.MassMatrixTextAreaLabel.Text = 'Mass Matrix';
            app.MassMatrixTextArea = uitextarea(app.ModalAnalysisTab_2);
            app.StiffnessMatrixTextAreaLabel = uilabel(app.ModalAnalysisTab_2);
            app.StiffnessMatrixTextAreaLabel.Text = 'Stiffness Matrix';
            app.StiffnessMatrixTextArea = uitextarea(app.ModalAnalysisTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel = uilabel(app.ModalAnalysisTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel.Text = 'Mass Normalized Eigenvectors';
            app.MassNormalizedEigenvectorsTextArea = uitextarea(app.ModalAnalysisTab_2);
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.SaveButton = uibutton(app.ExportTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MassEditFieldLabel = uilabel(app.ExportTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ExportTab, 'text');
            app.MassEditField.Value = 'two_dof_mass';
            app.StiffnessEditFieldLabel = uilabel(app.ExportTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.ExportTab, 'text');
            app.StiffnessEditField.Value = 'two_dof_stiffness';
            app.NaturalFrequenciesEditFieldLabel = uilabel(app.ExportTab);
            app.NaturalFrequenciesEditFieldLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesEditField = uieditfield(app.ExportTab, 'text');
            app.NaturalFrequenciesEditField.Value = 'two_dof_fn';
            app.ModeShapesEditFieldLabel = uilabel(app.ExportTab);
            app.ModeShapesEditFieldLabel.Text = 'Mode Shapes';
            app.ModeShapesEditField = uieditfield(app.ExportTab, 'text');
            app.ModeShapesEditField.Value = 'two_dof_modes';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.SelectTypesLabel = uilabel(app.DampingTab);
            app.SelectTypesLabel.Text = 'Select Types';
            app.ListBoxQ = uilistbox(app.DampingTab);
            app.ListBoxQ.Items = {'Amplification Factor Q', 'Viscous Damping Coefficient'};
            app.ListBoxQ.ValueChangedFcn = createCallbackFcn(app, @ListBoxQValueChanged, true);
            app.ListBoxQ.Value = 'Amplification Factor Q';
            app.ListBoxUniform = uilistbox(app.DampingTab);
            app.ListBoxUniform.Items = {'Uniform', 'Varies by Mode'};
            app.ListBoxUniform.ValueChangedFcn = createCallbackFcn(app, @ListBoxUniformValueChanged, true);
            app.ListBoxUniform.Value = 'Uniform';
            app.DampingUnitLabel = uilabel(app.DampingTab);
            app.DampingUnitLabel.Text = 'DampingUnit';
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.Mode1EditFieldLabel = uilabel(app.DampingTab);
            app.Mode1EditFieldLabel.Text = 'Mode 1';
            app.Mode1EditField = uieditfield(app.DampingTab, 'numeric');
            app.Mode1EditField.Value = 10;
            app.Mode2EditFieldLabel = uilabel(app.DampingTab);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.DampingTab, 'numeric');
            app.Mode2EditField.Value = 10;
            app.ForcedResponseTab = uitab(app.TabGroup);
            app.ForcedResponseTab.Title = 'Forced Response';
            app.TabGroup3 = uitabgroup(app.ForcedResponseTab);
            app.FRFTab_2 = uitab(app.TabGroup3);
            app.FRFTab_2.Title = 'FRF';
            app.TabGroup4 = uitabgroup(app.FRFTab_2);
            app.CalculateTab = uitab(app.TabGroup4);
            app.CalculateTab.Title = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.EndFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.EndFreqHzEditFieldLabel.Text = {'End Freq (Hz)'; ''};
            app.EndFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.ForcedofListBoxLabel = uilabel(app.CalculateTab);
            app.ForcedofListBoxLabel.Text = 'Force dof';
            app.ForcedofListBox = uilistbox(app.CalculateTab);
            app.ForcedofListBox.Items = {'1', '2'};
            app.ForcedofListBox.Value = '1';
            app.ResponsedofListBoxLabel = uilabel(app.CalculateTab);
            app.ResponsedofListBoxLabel.Text = 'Response dof';
            app.ResponsedofListBox = uilistbox(app.CalculateTab);
            app.ResponsedofListBox.Items = {'1', '2'};
            app.ResponsedofListBox.Value = '1';
            app.CalculateButton_Trans = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_TransPushed, true);
            app.CalculateButton_Trans.Text = 'Calculate';
            app.ExportTab_2 = uitab(app.TabGroup4);
            app.ExportTab_2.Title = 'Export';
            app.ExportFormatLabel = uilabel(app.ExportTab_2);
            app.ExportFormatLabel.Text = 'Export Format';
            app.AmplitudeTypeLabel = uilabel(app.ExportTab_2);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.Label = uilabel(app.ExportTab_2);
            app.Label.Text = '';
            app.ExportTrans = uilistbox(app.ExportTab_2);
            app.ExportTrans.Items = {'Freq & Magnitude', 'Freq & Magnitude & Phase', 'Freq & Complex Magnitude'};
            app.ExportTrans.Value = 'Freq & Magnitude';
            app.Label_2 = uilabel(app.ExportTab_2);
            app.Label_2.Text = '';
            app.ExportAmplitude = uilistbox(app.ExportTab_2);
            app.ExportAmplitude.Items = {'Acceleration/Force', 'Velocity/Force', 'Displacement/Force'};
            app.ExportAmplitude.Value = 'Acceleration/Force';
            app.SaveButton_Trans = uibutton(app.ExportTab_2, 'push');
            app.SaveButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_TransPushed, true);
            app.SaveButton_Trans.Text = 'Save';
            app.OutputArrayNameEditField_2Label = uilabel(app.ExportTab_2);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditFieldTrans = uieditfield(app.ExportTab_2, 'text');
            app.SineTab_2 = uitab(app.TabGroup3);
            app.SineTab_2.Title = 'Sine';
            app.SteadyStateAppliedForceLabel = uilabel(app.SineTab_2);
            app.SteadyStateAppliedForceLabel.Text = 'Steady-State Applied Force';
            app.CalculateButton_Sine = uibutton(app.SineTab_2, 'push');
            app.CalculateButton_Sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SinePushed2, true);
            app.CalculateButton_Sine.Text = 'Calculate';
            app.SettoFirstModalFreqButton = uibutton(app.SineTab_2, 'push');
            app.SettoFirstModalFreqButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstModalFreqButtonPushed2, true);
            app.SettoFirstModalFreqButton.Text = 'Set to First Modal Freq';
            app.FreqHzEditFieldLabel = uilabel(app.SineTab_2);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SineTab_2, 'numeric');
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.AppliedForcedofListBoxLabel = uilabel(app.SineTab_2);
            app.AppliedForcedofListBoxLabel.Text = 'Applied Force dof';
            app.AppliedForcedofListBox = uilistbox(app.SineTab_2);
            app.AppliedForcedofListBox.Items = {'1', '2'};
            app.AppliedForcedofListBox.ValueChangedFcn = createCallbackFcn(app, @AppliedForcedofListBoxValueChanged, true);
            app.AppliedForcedofListBox.Value = '1';
            app.ForcelbfEditFieldLabel = uilabel(app.SineTab_2);
            app.ForcelbfEditFieldLabel.Text = 'Force (lbf)';
            app.ForcelbfEditField = uieditfield(app.SineTab_2, 'numeric');
            app.ForcelbfEditField.ValueChangedFcn = createCallbackFcn(app, @ForcelbfEditFieldValueChanged, true);
            app.HalfSineTab_2 = uitab(app.TabGroup3);
            app.HalfSineTab_2.Title = 'Half-Sine';
            app.TabGroup5 = uitabgroup(app.HalfSineTab_2);
            app.CalculateTab_2 = uitab(app.TabGroup5);
            app.CalculateTab_2.Title = 'Calculate';
            app.ResponsetoHalfSineAppliedForceLaplaceTransformLabel = uilabel(app.CalculateTab_2);
            app.ResponsetoHalfSineAppliedForceLaplaceTransformLabel.Text = 'Response to Half-Sine Applied Force, Laplace Transform';
            app.AppliedForcelbfLabel = uilabel(app.CalculateTab_2);
            app.AppliedForcelbfLabel.Text = 'Applied Force (lbf)';
            app.AppliedHSForceEditField = uieditfield(app.CalculateTab_2, 'numeric');
            app.AppliedHSForceEditField.ValueChangedFcn = createCallbackFcn(app, @AppliedHSForceEditFieldValueChanged, true);
            app.PulseDurationsecEditFieldLabel = uilabel(app.CalculateTab_2);
            app.PulseDurationsecEditFieldLabel.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField = uieditfield(app.CalculateTab_2, 'numeric');
            app.PulseDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationsecEditFieldValueChanged, true);
            app.AppliedForcedofListBox_2Label = uilabel(app.CalculateTab_2);
            app.AppliedForcedofListBox_2Label.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_HS = uilistbox(app.CalculateTab_2);
            app.AppliedForcedofListBox_HS.Items = {'1', '2'};
            app.AppliedForcedofListBox_HS.ValueChangedFcn = createCallbackFcn(app, @AppliedForcedofListBox_HSValueChanged, true);
            app.AppliedForcedofListBox_HS.Value = '1';
            app.CalculateButton_HalfSine = uibutton(app.CalculateTab_2, 'push');
            app.CalculateButton_HalfSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_HalfSinePushed, true);
            app.CalculateButton_HalfSine.Text = 'Calculate';
            app.TotalDurationsecEditFieldLabel = uilabel(app.CalculateTab_2);
            app.TotalDurationsecEditFieldLabel.Text = 'Total Duration (sec)';
            app.TotalDurationsecEditField = uieditfield(app.CalculateTab_2, 'numeric');
            app.TotalDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @TotalDurationsecEditFieldValueChanged, true);
            app.ExportTab_3 = uitab(app.TabGroup5);
            app.ExportTab_3.Title = 'Export';
            app.OutputTimeHistoryArrayListBoxLabel = uilabel(app.ExportTab_3);
            app.OutputTimeHistoryArrayListBoxLabel.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox = uilistbox(app.ExportTab_3);
            app.OutputTimeHistoryArrayListBox.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Acceleration 1', 'Acceleration 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputTimeHistoryArrayListBox.Value = 'Displacement 1 & 2';
            app.SaveHSButton = uibutton(app.ExportTab_3, 'push');
            app.SaveHSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHSButtonPushed, true);
            app.SaveHSButton.Text = 'Save Output';
            app.OutputArrayNameEditField_3Label = uilabel(app.ExportTab_3);
            app.OutputArrayNameEditField_3Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_HS = uieditfield(app.ExportTab_3, 'text');
            app.SaveDiracButton_3 = uibutton(app.ExportTab_3, 'push');
            app.SaveDiracButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveDiracButton_3Pushed, true);
            app.SaveDiracButton_3.Text = 'H1, H2, Modal';
            app.ArbitraryTab_2 = uitab(app.TabGroup3);
            app.ArbitraryTab_2.Title = 'Arbitrary';
            app.TabGroup7 = uitabgroup(app.ArbitraryTab_2);
            app.CalculateTab_3 = uitab(app.TabGroup7);
            app.CalculateTab_3.Title = 'Calculate';
            app.ResponsetoarbitraryappliedforceLabel = uilabel(app.CalculateTab_3);
            app.ResponsetoarbitraryappliedforceLabel.Text = 'Response to arbitrary applied force.  Ramp invariant digital recursive filtering relationship.';
            app.InputArbitraryForceArray = uilabel(app.CalculateTab_3);
            app.InputArbitraryForceArray.Text = 'The input array must have two columns:  Time(sec) & Force (lbf)';
            app.CalculateButton_Arbitrary = uibutton(app.CalculateTab_3, 'push');
            app.CalculateButton_Arbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_ArbitraryPushed, true);
            app.CalculateButton_Arbitrary.Text = 'Calculate';
            app.AppliedForcedofListBox_2Label_2 = uilabel(app.CalculateTab_3);
            app.AppliedForcedofListBox_2Label_2.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_Arbitrary = uilistbox(app.CalculateTab_3);
            app.AppliedForcedofListBox_Arbitrary.Items = {'1', '2', '1 & 2'};
            app.AppliedForcedofListBox_Arbitrary.ValueChangedFcn = createCallbackFcn(app, @AppliedForcedofListBox_ArbitraryValueChanged, true);
            app.AppliedForcedofListBox_Arbitrary.Value = '1';
            app.SelectInputArrayListBox_5Label = uilabel(app.CalculateTab_3);
            app.SelectInputArrayListBox_5Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_arb_force = uilistbox(app.CalculateTab_3);
            app.SelectInputArrayListBox_arb_force.Items = {'Item 1'};
            app.UpdateListButton_arb_force = uibutton(app.CalculateTab_3, 'push');
            app.UpdateListButton_arb_force.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_arb_forcePushed, true);
            app.UpdateListButton_arb_force.Text = 'Update List';
            app.SaveDiracButton_5 = uibutton(app.CalculateTab_3, 'push');
            app.SaveDiracButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveDiracButton_5Pushed, true);
            app.SaveDiracButton_5.Text = 'H1, H2, Modal';
            app.ExportTab_5 = uitab(app.TabGroup7);
            app.ExportTab_5.Title = 'Export';
            app.OutputTimeHistoryArrayListBox_2Label = uilabel(app.ExportTab_5);
            app.OutputTimeHistoryArrayListBox_2Label.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox_Arbitrary = uilistbox(app.ExportTab_5);
            app.OutputTimeHistoryArrayListBox_Arbitrary.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Acceleration 1', 'Acceleration 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputTimeHistoryArrayListBox_Arbitrary.Value = 'Displacement 1 & 2';
            app.SaveArbitraryButton = uibutton(app.ExportTab_5, 'push');
            app.SaveArbitraryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArbitraryButtonPushed, true);
            app.SaveArbitraryButton.Text = 'Save';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTab_5);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField_arb = uieditfield(app.ExportTab_5, 'text');
            app.SaveDiracButton_4 = uibutton(app.ExportTab_5, 'push');
            app.SaveDiracButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveDiracButton_4Pushed, true);
            app.SaveDiracButton_4.Text = 'H1, H2, Modal';
            app.PSDTab_2 = uitab(app.TabGroup3);
            app.PSDTab_2.Title = 'PSD';
            app.TabGroup8 = uitabgroup(app.PSDTab_2);
            app.CalculateTab_4 = uitab(app.TabGroup8);
            app.CalculateTab_4.Title = 'Calculate';
            app.InputArrayNamePSD = uilabel(app.CalculateTab_4);
            app.InputArrayNamePSD.Text = {'The PSD input array must have two columns:  Freq(Hz) & Force(lbf^2/Hz)'; ''};
            app.CalculateButton_PSD = uibutton(app.CalculateTab_4, 'push');
            app.CalculateButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PSDPushed, true);
            app.CalculateButton_PSD.Text = 'Calculate';
            app.AppliedForcedofListBox_3Label = uilabel(app.CalculateTab_4);
            app.AppliedForcedofListBox_3Label.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_PSD = uilistbox(app.CalculateTab_4);
            app.AppliedForcedofListBox_PSD.Items = {'1', '2', '1 & 2 Fully Correlated', '1 & 2 Fully Uncorrelated'};
            app.AppliedForcedofListBox_PSD.Value = '1';
            app.SelectInputArrayListBox_5Label_2 = uilabel(app.CalculateTab_4);
            app.SelectInputArrayListBox_5Label_2.Text = 'Select Input PSD Array';
            app.SelectInputPSDArrayListBox = uilistbox(app.CalculateTab_4);
            app.SelectInputPSDArrayListBox.Items = {'Item 1'};
            app.UpdateListButton_arb_force_2 = uibutton(app.CalculateTab_4, 'push');
            app.UpdateListButton_arb_force_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_arb_force_2Pushed, true);
            app.UpdateListButton_arb_force_2.Text = 'Update List';
            app.ExportTab_6 = uitab(app.TabGroup8);
            app.ExportTab_6.Title = 'Export';
            app.SaveButton_PSD = uibutton(app.ExportTab_6, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.ExportTab_6);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.ExportTab_6, 'text');
            app.OutputArrayListBoxLabel = uilabel(app.ExportTab_6);
            app.OutputArrayListBoxLabel.Text = 'Output Array';
            app.OutputArrayListBox_psd = uilistbox(app.ExportTab_6);
            app.OutputArrayListBox_psd.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputArrayListBox_psd.Value = 'Displacement 1 & 2';
            app.DiracDeltaTab_2 = uitab(app.TabGroup3);
            app.DiracDeltaTab_2.Title = 'Dirac Delta';
            app.TabGroup6 = uitabgroup(app.DiracDeltaTab_2);
            app.CaculateTab = uitab(app.TabGroup6);
            app.CaculateTab.Title = 'Caculate';
            app.ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel = uilabel(app.CaculateTab);
            app.ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel.Text = {'This script applies a Dirac delta impulse force to the top mass.  '; 'The solution method is the Laplace transform.'};
            app.TotalDurationsecEditField_DiracLabel = uilabel(app.CaculateTab);
            app.TotalDurationsecEditField_DiracLabel.Text = 'Total Duration (sec)';
            app.TotalDurationsecEditField_Dirac = uieditfield(app.CaculateTab, 'numeric');
            app.SampleRateHzEditField_Label_Dirac = uilabel(app.CaculateTab);
            app.SampleRateHzEditField_Label_Dirac.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_Dirac = uieditfield(app.CaculateTab, 'numeric');
            app.SampleRateHzEditField_Dirac.Value = 20000;
            app.CalculateButton_Dirac = uibutton(app.CaculateTab, 'push');
            app.CalculateButton_Dirac.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_DiracPushed, true);
            app.CalculateButton_Dirac.Text = 'Calculate';
            app.TotalImpulselbfsecEditField_Label_Dirac = uilabel(app.CaculateTab);
            app.TotalImpulselbfsecEditField_Label_Dirac.Text = 'Total Impulse (lbf sec)';
            app.TotalImpulselbfsecEditField_Dirac = uieditfield(app.CaculateTab, 'numeric');
            app.ExportTab_4 = uitab(app.TabGroup6);
            app.ExportTab_4.Title = 'Export';
            app.OutputTimeHistoryArrayListBox_Label_Dirace = uilabel(app.ExportTab_4);
            app.OutputTimeHistoryArrayListBox_Label_Dirace.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox_Dirac = uilistbox(app.ExportTab_4);
            app.OutputTimeHistoryArrayListBox_Dirac.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2'};
            app.OutputTimeHistoryArrayListBox_Dirac.Value = 'Displacement 1 & 2';
            app.SaveDiracButton = uibutton(app.ExportTab_4, 'push');
            app.SaveDiracButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDiracButtonPushed, true);
            app.SaveDiracButton.Text = 'Save';
            app.OutputArrayNameEditField_Label_Dirace = uilabel(app.ExportTab_4);
            app.OutputArrayNameEditField_Label_Dirace.Text = 'Output Array Name';
            app.OutputArrayNameEditField_Dirac = uieditfield(app.ExportTab_4, 'text');
            app.ResultsReadOnlyTextAreaLabel = uilabel(app.ForcedResponseTab);
            app.ResultsReadOnlyTextAreaLabel.Text = 'Results , Read Only';
            app.ResultsReadOnlyTextArea = uitextarea(app.ForcedResponseTab);
            app.ResultsReadOnlyTextArea.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.twodofforceUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SelectTypesLabel.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.twodofforceUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.SaveHSButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.twodofforceUIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.SaveDiracButton_3.ContextMenu = app.ContextMenu3;
            app.SaveDiracButton_5.ContextMenu = app.ContextMenu3;
            app.SaveDiracButton_4.ContextMenu = app.ContextMenu3;
            app.SaveDiracButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.twodofforceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_force_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.twodofforceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.twodofforceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.twodofforceUIFigure)
        end
    end
end