classdef two_dof_system_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SelectSystemTab                 matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        SelectButton_4                  matlab.ui.control.Button
        SelectButton_3                  matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        SelectButton_1                  matlab.ui.control.Button
        Image2_5                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        MassStiffnessTab                matlab.ui.container.Tab
        GoToModalButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Image_mk                        matlab.ui.control.Image
        ModalAnalysisTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CalculateTab_2                  matlab.ui.container.Tab
        NaturalFrequenciesTextArea      matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        MassNormalizedEigenvectorsTextArea  matlab.ui.control.TextArea
        MassNormalizedEigenvectorsTextAreaLabel  matlab.ui.control.Label
        StiffnessMatrixTextArea         matlab.ui.control.TextArea
        StiffnessMatrixTextAreaLabel    matlab.ui.control.Label
        MassMatrixTextArea              matlab.ui.control.TextArea
        MassMatrixTextAreaLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Image_modal                     matlab.ui.control.Image
        SaveTab_2                       matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        ParticipationFactorEditField    matlab.ui.control.EditField
        ParticipationFactorEditFieldLabel  matlab.ui.control.Label
        ModeShapesEditField             matlab.ui.control.EditField
        ModeShapesEditFieldLabel        matlab.ui.control.Label
        NaturalFrequencyEditField       matlab.ui.control.EditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        StiffnessEditField              matlab.ui.control.EditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        MassEditField                   matlab.ui.control.EditField
        MassEditFieldLabel              matlab.ui.control.Label
        OutputArrayNamesLabel           matlab.ui.control.Label
        DampingTab_2                    matlab.ui.container.Tab
        Mode2EditField                  matlab.ui.control.NumericEditField
        Mode2EditFieldLabel             matlab.ui.control.Label
        Mode1EditField                  matlab.ui.control.NumericEditField
        Mode1EditFieldLabel             matlab.ui.control.Label
        ListBoxQ                        matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        ListBoxUniform                  matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        DampingUnitLabel                matlab.ui.control.Label
        SelectTypesLabel                matlab.ui.control.Label
        BaseExcitationTab_2             matlab.ui.container.Tab
        Image_base                      matlab.ui.control.Image
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        TransTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        CalculateButton_Trans           matlab.ui.control.Button
        EndFreqHzEditField              matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.NumericEditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        SaveButton_Trans                matlab.ui.control.Button
        OutputArrayNameEditFieldTrans   matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        ExportArrayLabel                matlab.ui.control.Label
        AmplitudeTypeLabel              matlab.ui.control.Label
        ExportAmplitude                 matlab.ui.control.ListBox
        Label_4                         matlab.ui.control.Label
        ExportTrans                     matlab.ui.control.ListBox
        Label_3                         matlab.ui.control.Label
        SineTab                         matlab.ui.container.Tab
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        AccelGEditField                 matlab.ui.control.NumericEditField
        AccelGEditFieldLabel            matlab.ui.control.Label
        SettoFirstModalFreqButton       matlab.ui.control.Button
        SteadyStateSineBaseInputLabel   matlab.ui.control.Label
        CalculateButton_sine            matlab.ui.control.Button
        HalfSineTab                     matlab.ui.container.Tab
        DurationsecEditField            matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        BaseInputAccelGEditField        matlab.ui.control.NumericEditField
        BaseInputAccelGEditFieldLabel   matlab.ui.control.Label
        ResponsetoHalfSineBaseInputLabel  matlab.ui.control.Label
        CalculateButton_halfsine        matlab.ui.control.Button
        PSDTab                          matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        CalculateTab_4                  matlab.ui.container.Tab
        CalculateButton_psd             matlab.ui.control.Button
        SaveTab_4                       matlab.ui.container.Tab
        ArbitraryTab                    matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        CalculateTab_3                  matlab.ui.container.Tab
        CalculateButton_arbitrary       matlab.ui.control.Button
        SaveTab_3                       matlab.ui.container.Tab
        SaveButton_arbitrary            matlab.ui.control.Button
        MDOFSRSTab                      matlab.ui.container.Tab
        CalculateButton_mdof_srs        matlab.ui.control.Button
        two_dof_system_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iflag      % Description
        damp1      %
        damp2      %
        fn         %
        ModeShapes %
        MST        %
        mm         %
        pff        %
        iu         %
        mass       %
        stiffness  %
        omegan     %
    end
    
    methods (Access = private)
        
        function set_table(app)
             
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            if(app.iu==1)
                sm='lbm';
                sk='lbf/in';
                sL='in';
                sJ='lbm in^2';
            else
                sm='kg';
                sk='N/m';
                sL='m';
                sJ='kg m^2';
            end    

            if(app.iflag==1)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
            end 
            if(app.iflag==2)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K1';
                ss{4,1}='K2';

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';  

                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
                ss{4,3}=sk;                
            end    
            if(app.iflag==3)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K1';
                ss{4,1}='K2';
                ss{5,1}='K3';

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';  
                ss{5,2}='';               

                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
                ss{4,3}=sk; 
                ss{5,3}=sk;             
            end 
            if(app.iflag==4)
                ss{1,1}='M';
                ss{2,1}='J';
                ss{3,1}='K1';
                ss{4,1}='K2';
                ss{5,1}='L1';
                ss{6,1}='L2';               

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';    
                ss{5,2}='';              
                ss{6,2}='';                

                ss{1,3}=sm;
                ss{2,3}=sJ;
                ss{3,3}=sk;
                ss{4,3}=sk;      
                ss{5,3}=sL;
                ss{6,3}=sL;                   
            end                 

            app.UITable.Data=ss; 
        end    
 
        function clear_modal(app)
            app.MassMatrixTextArea.Visible='off';
            app.StiffnessMatrixTextArea.Visible='off';
            app.MassNormalizedEigenvectorsTextArea.Visible='off';
            app.NaturalFrequenciesTextArea.Visible='off';
            app.MassMatrixTextAreaLabel.Visible='off';
            app.StiffnessMatrixTextAreaLabel.Visible='off';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='off';
            app.NaturalFrequenciesTextAreaLabel.Visible='off';            
        end    

        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end    

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function check(app)
            try
                if(min(app.fn)<=0)
                    warndlg('Zero frequency detected. Run Natural Frequency Tab')
                    return;
                end
            catch
                warndlg('Run Natural Freuqency Tab')
                return;
            end
            
            try
                if(app.damp1>=0 && app.damp2>=0)
                else    
                    warndlg('Enter Damping at Damping Tab');
                    return;                
                end
            catch
                warndlg('Enter Damping at Damping Tab');
                return;
            end
            
        end        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear(app);
            app.iflag=2;
            app.Image_mk.Position = [10 160 178 184];
            app.Image_mk.ImageSource = 'twodof_a.jpg';        
            app.Image_modal.Position = [10 160 178 184];
            app.Image_modal.ImageSource = 'twodof_a.jpg';  
            set_table(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SelectButton_1
        function SelectButton_1Pushed(app, event)
            clear(app);
            app.iflag=1;
            app.Image_mk.Position = [180 170 203 174];
            app.Image_mk.ImageSource = 'semidefinite.jpg';  
            app.Image_modal.Position = [10 160 203 174];
            app.Image_modal.ImageSource = 'semidefinite.jpg';        
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            clear(app);
            app.iflag=2;
            app.Image_mk.Position = [200 160 178 184];
            app.Image_mk.ImageSource = 'twodof_a.jpg';   
            app.Image_modal.Position = [10 160 178 184];
            app.Image_modal.ImageSource = 'twodof_a.jpg';              
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            clear(app);
            app.iflag=3;
            app.Image_mk.Position = [40 140 round(409*1.3) round(166*1.3)];     
            app.Image_mk.ImageSource = 'two_dof_system_b.jpg';  
            app.Image_modal.Position = [1 130 round(409*1.2) round(166*1.2)];     
            app.Image_modal.ImageSource = 'two_dof_system_b.jpg';
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            clear(app);
            app.iflag=4;
            app.Image_mk.Position = [1 180 409 166];
            app.Image_mk.ImageSource = 'two_dof_system_c.jpg'; 
            app.Image_modal.Position = [1 150 round(409*0.9) round(166*0.9)];
            app.Image_modal.ImageSource = 'two_dof_system_c.jpg';
            app.Image_base.ImageSource ='two_dof_base_di.jpg';
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                if(contains(m,'Uniform'))
                     Q1=app.Mode1EditField.Value;
                     Q2=Q1;
                     app.damp1=1/(2*Q1);
                     app.damp2=app.damp1;
                else
                     Q1=app.Mode1EditField.Value;
                     Q2=app.Mode2EditField.Value;
                     app.damp1=1/(2*Q1);
                     app.damp2=1/(2*Q2);                
                end    
            else
                if(contains(m,'Uniform'))
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.damp1;
                     Q1=1/(2*app.damp1);
                     Q2=Q1;              
                else
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.Mode2EditField.Value;
                     Q1=1/(2*app.damp1);
                     Q2=1/(2*app.damp2);                   
                end                   
            end         
            
            app.dm=[app.damp1 app.damp2];
            
            fprintf('\n Damping Ratios \n Mode 1       2        \n');
            fprintf('    %6.3g  %6.3g  \n',app.damp1,app.damp2);
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            A=char(app.UITable.Data);

            sz=size(A);
            N=round(sz(1)/3);

            app.iflag
                      
            if(app.iflag==1)
                m1=str2num(A(N+1))
                m2=str2num(A(N+2))
                k=str2num(strtrim(A(N+3)))
                app.stiffness=[ k -k ; -k k];
            end    
            if(app.iflag==2)
                m1=str2num(A(N+1));
                m2=str2num(A(N+2));
                k1=str2num(A(N+3));
                k2=str2num(A(N+4));      
                app.stiffness=[ (k1+k2) -k2 ; -k2 k2];             
            end   
            if(app.iflag==3)
                m1=str2num(A(N+1));
                m2=str2num(A(N+2));
                k1=str2num(A(N+3));
                k2=str2num(A(N+4));  
                k3=str2num(A(N+5));    
                app.stiffness=[(k1+k2) -k2; -k2 (k2+k3)];       
            end   
            if(app.iflag==4)
                m1=str2num(A(N+1));
                m2=str2num(A(N+2));
                k1=str2num(A(N+3));
                k2=str2num(A(N+4));  
                L1=str2num(A(N+5));
                L2=str2num(A(N+6)); 
                app.stiffness(1,1)=k1+k2;
                app.stiffness(1,2)=-k1*L1+k2*L2;
                app.stiffness(2,2)=k1*L1^2+k2*L2^2;
                app.stiffness(2,1)=app.stiffness(1,2);             
            end               

            app.mass=[m1 0; 0 m2 ]; 
                
            if(app.iu==1)
                app.mass=app.mass/386.;
            end
 
            %%%

            [app.fn,app.ModeShapes,app.pff,~]=tdof_fn_etr_results(app.mass,app.stiffness,app.iu);
            
            app.omegan=2*pi*app.fn;  
            app.MST=app.ModeShapes';       
          
            disp('  ');
            disp(' * * * * * * ');
            disp(' ');
            
            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiffness,app.mass,2);
            %
            dof=length(app.fn);
            %
            v=ones(dof,1)';

            if(app.iu==1)
                s1='Mass Matrix (lbf sec^2/in)';
            else
                s1='Mass Matrix (kg)';    
            end
            app.MassMatrixTextAreaLabel.Text=s1;

            m=app.mass;
            ss_m=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',m(1,1),m(1,2),m(2,1),m(2,2));
            
            k=app.stiffness;
            if(app.iu==1)
                s2='Stiffness Matrix (lbf/in)';
            else
                s2='Stiffness Matrix (N/m)';    
            end
            app.StiffnessMatrixTextAreaLabel.Text=s2; 
            ss_k=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',k(1,1),k(1,2),k(2,1),k(2,2));

            e=app.ModeShapes;
            ss_e=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',e(1,1),e(1,2),e(2,1),e(2,2));
            
            %
            ss_f='            Natural      Participation    Effective Modal';
            
            if(app.iu==1)
                ss_ff='Mode   Freq (Hz)        Factor          Mass (lbm)';
            else
                ss_ff='Mode   Freq (Hz)        Factor          Mass (kg)';    
            end    

            ss_f=sprintf('%s\n%s\n',ss_f,ss_ff);
            
            %
            LM=app.MST*app.mass*v';
            pf=LM;
            sum=0;
            %    
            mmm=app.MST*app.mass*app.ModeShapes;   
            %
            
            app.pff=zeros(dof,1);
            emm=zeros(dof,1);
            
            app.fn(abs(app.fn)<1.0e-05) = 0;
            
            for i=1:dof
                    app.pff(i)=pf(i)/mmm(i,i);
                    emm(i)=pf(i)^2/mmm(i,i);
                    if(app.iu==1)
                        out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i)*386);
                        sum=sum+emm(i);            
                    else
                        out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t   %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i));
                        sum=sum+emm(i);              
                    end    
                    ss_f=sprintf('%s\n%s',ss_f,out1);
            end
            if(app.iu==1)
                out1=sprintf('\n modal mass sum = %8.4g lbm \n',sum*386);
            else
                out1=sprintf('\n modal mass sum = %8.4g kg \n',sum);    
            end
            ss_fn=sprintf('%s\n%s\n',ss_f,out1);

            app.MassMatrixTextArea.Value=ss_m;
            app.StiffnessMatrixTextArea.Value=ss_k;
            app.MassNormalizedEigenvectorsTextArea.Value=ss_e;
            app.NaturalFrequenciesTextArea.Value=ss_fn;

            app.MassMatrixTextAreaLabel.Visible='on';
            app.MassMatrixTextArea.Visible='on';
            app.StiffnessMatrixTextArea.Visible='on';
            app.MassNormalizedEigenvectorsTextArea.Visible='on';
            app.NaturalFrequenciesTextArea.Visible='on';

            app.MassMatrixTextAreaLabel.Visible='on';
            app.StiffnessMatrixTextAreaLabel.Visible='on';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='on';
            app.NaturalFrequenciesTextAreaLabel.Visible='on'; 

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            name_mass=strtrim(app.MassEditField.Value);
            name_stiffness=strtrim(app.StiffnessEditField.Value);
            name_two_dof_fn=strtrim(app.NaturalFrequencyEditField.Value);
            name_ModeShapes=strtrim(app.ModeShapesEditField.Value);
            name_PF=strtrim(app.ParticipationFactorEditField.Value);

            try
                assignin('base', name_mass, app.mass);
                assignin('base', name_stiffness, app.stiffness);
                assignin('base', name_two_dof_fn, app.fn);
                assignin('base', name_ModeShapes, app.ModeShapes);
                assignin('base', name_PF', app.pff);
                msgbox('Arrays Saved');           
            catch
                warndlg('Save failed');
            end
        end

        % Button pushed function: CalculateButton_mdof_srs
        function CalculateButton_mdof_srsPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: CalculateButton_Trans
        function CalculateButton_TransPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end    
        end

        % Button pushed function: CalculateButton_sine
        function CalculateButton_sinePushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: CalculateButton_halfsine
        function CalculateButton_halfsinePushed(app, event)
             clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: CalculateButton_psd
        function CalculateButton_psdPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: CalculateButton_arbitrary
        function CalculateButton_arbitraryPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: SaveButton_Trans
        function SaveButton_TransPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Button pushed function: SaveButton_arbitrary
        function SaveButton_arbitraryPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            set_table(app);
        end

        % Button pushed function: GoToModalButton
        function GoToModalButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.two_dof_system_aver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.two_dof_system_aver10byTomIrvineLabel.Text = 'two_dof_system_a      ver 1.0      by Tom Irvine';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectSystemTab = uitab(app.TabGroup);
            app.SelectSystemTab.Title = 'Select System';
            app.Image2_2 = uiimage(app.SelectSystemTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'semidefinite.jpg');
            app.Image2_3 = uiimage(app.SelectSystemTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'twodof_a.jpg');
            app.Image2_4 = uiimage(app.SelectSystemTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'two_dof_system_b.jpg');
            app.Image2_5 = uiimage(app.SelectSystemTab);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'two_dof_system_c.jpg');
            app.SelectButton_1 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_1.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_1Pushed, true);
            app.SelectButton_1.Text = 'Select';
            app.SelectButton_2 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SelectButton_3 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.SelectButton_4 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.UnitsListBoxLabel = uilabel(app.SelectSystemTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SelectSystemTab);
            app.UnitsListBox.Items = {'English', 'Metric', ''};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MassStiffnessTab = uitab(app.TabGroup);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.Image_mk = uiimage(app.MassStiffnessTab);
            app.UITable = uitable(app.MassStiffnessTab);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.GoToModalButton = uibutton(app.MassStiffnessTab, 'push');
            app.GoToModalButton.ButtonPushedFcn = createCallbackFcn(app, @GoToModalButtonPushed, true);
            app.GoToModalButton.Text = 'Go To Modal';
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.TabGroup5 = uitabgroup(app.ModalAnalysisTab);
            app.CalculateTab_2 = uitab(app.TabGroup5);
            app.CalculateTab_2.Title = 'Calculate';
            app.Image_modal = uiimage(app.CalculateTab_2);
            app.CalculateButton = uibutton(app.CalculateTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassMatrixTextAreaLabel = uilabel(app.CalculateTab_2);
            app.MassMatrixTextAreaLabel.Text = 'Mass Matrix';
            app.MassMatrixTextArea = uitextarea(app.CalculateTab_2);
            app.StiffnessMatrixTextAreaLabel = uilabel(app.CalculateTab_2);
            app.StiffnessMatrixTextAreaLabel.Text = 'Stiffness Matrix';
            app.StiffnessMatrixTextArea = uitextarea(app.CalculateTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel = uilabel(app.CalculateTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel.Text = 'Mass Normalized Eigenvectors';
            app.MassNormalizedEigenvectorsTextArea = uitextarea(app.CalculateTab_2);
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.CalculateTab_2);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.CalculateTab_2);
            app.SaveTab_2 = uitab(app.TabGroup5);
            app.SaveTab_2.Title = 'Save';
            app.OutputArrayNamesLabel = uilabel(app.SaveTab_2);
            app.OutputArrayNamesLabel.Text = 'Output Array Names';
            app.MassEditFieldLabel = uilabel(app.SaveTab_2);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.SaveTab_2, 'text');
            app.MassEditField.Value = ' two_dof_mass';
            app.StiffnessEditFieldLabel = uilabel(app.SaveTab_2);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.SaveTab_2, 'text');
            app.StiffnessEditField.Value = 'two_dof_stiffness';
            app.NaturalFrequencyEditFieldLabel = uilabel(app.SaveTab_2);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyEditField = uieditfield(app.SaveTab_2, 'text');
            app.NaturalFrequencyEditField.Value = 'two_dof_fn';
            app.ModeShapesEditFieldLabel = uilabel(app.SaveTab_2);
            app.ModeShapesEditFieldLabel.Text = 'Mode Shapes';
            app.ModeShapesEditField = uieditfield(app.SaveTab_2, 'text');
            app.ModeShapesEditField.Value = 'two_dof_ModeShapes';
            app.ParticipationFactorEditFieldLabel = uilabel(app.SaveTab_2);
            app.ParticipationFactorEditFieldLabel.Text = 'Participation Factor';
            app.ParticipationFactorEditField = uieditfield(app.SaveTab_2, 'text');
            app.ParticipationFactorEditField.Value = 'two_dof_PF';
            app.SaveButton = uibutton(app.SaveTab_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DampingTab_2 = uitab(app.TabGroup);
            app.DampingTab_2.Title = 'Damping';
            app.SelectTypesLabel = uilabel(app.DampingTab_2);
            app.SelectTypesLabel.Text = 'Select Types';
            app.DampingUnitLabel = uilabel(app.DampingTab_2);
            app.DampingUnitLabel.Text = 'DampingUnit';
            app.SaveDampingButton = uibutton(app.DampingTab_2, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.Label = uilabel(app.DampingTab_2);
            app.Label.Text = '';
            app.ListBoxUniform = uilistbox(app.DampingTab_2);
            app.ListBoxUniform.Items = {'Uniform', 'Varies by Mode'};
            app.ListBoxUniform.Value = 'Uniform';
            app.Label_2 = uilabel(app.DampingTab_2);
            app.Label_2.Text = '';
            app.ListBoxQ = uilistbox(app.DampingTab_2);
            app.ListBoxQ.Items = {'Amplification Factor Q', 'Viscous Damping Coefficient'};
            app.ListBoxQ.Value = 'Amplification Factor Q';
            app.Mode1EditFieldLabel = uilabel(app.DampingTab_2);
            app.Mode1EditFieldLabel.Text = 'Mode 1';
            app.Mode1EditField = uieditfield(app.DampingTab_2, 'numeric');
            app.Mode2EditFieldLabel = uilabel(app.DampingTab_2);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.DampingTab_2, 'numeric');
            app.BaseExcitationTab_2 = uitab(app.TabGroup);
            app.BaseExcitationTab_2.Title = 'Base Excitation';
            app.TabGroup3 = uitabgroup(app.BaseExcitationTab_2);
            app.TransTab = uitab(app.TabGroup3);
            app.TransTab.Title = 'Trans';
            app.TabGroup4 = uitabgroup(app.TransTab);
            app.CalculateTab = uitab(app.TabGroup4);
            app.CalculateTab.Title = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.StartFreqHzEditField.Value = 10;
            app.EndFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.EndFreqHzEditFieldLabel.Text = {'End Freq (Hz)'; ''};
            app.EndFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.EndFreqHzEditField.Value = 2000;
            app.CalculateButton_Trans = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_TransPushed, true);
            app.CalculateButton_Trans.Text = 'Calculate';
            app.SaveTab = uitab(app.TabGroup4);
            app.SaveTab.Title = 'Save';
            app.Label_3 = uilabel(app.SaveTab);
            app.Label_3.Text = '';
            app.ExportTrans = uilistbox(app.SaveTab);
            app.ExportTrans.Items = {'Transmissibility   -  freq (Hz) & (response/input)        ', 'Power Transmissibility   -  freq (Hz) & (response/input)^2', ''};
            app.ExportTrans.Value = 'Transmissibility   -  freq (Hz) & (response/input)        ';
            app.Label_4 = uilabel(app.SaveTab);
            app.Label_4.Text = '';
            app.ExportAmplitude = uilistbox(app.SaveTab);
            app.ExportAmplitude.Items = {'mass 1 acceleration                   ', 'mass 2 acceleration                   ', 'mass 1 - base  relative displacement  ', 'mass 2 - base  relative displacement  ', 'mass 2 - mass 1  relative displacement'};
            app.ExportAmplitude.Value = 'mass 1 acceleration                   ';
            app.AmplitudeTypeLabel = uilabel(app.SaveTab);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.ExportArrayLabel = uilabel(app.SaveTab);
            app.ExportArrayLabel.Text = 'Export Array';
            app.OutputArrayNameEditField_2Label = uilabel(app.SaveTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditFieldTrans = uieditfield(app.SaveTab, 'text');
            app.SaveButton_Trans = uibutton(app.SaveTab, 'push');
            app.SaveButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_TransPushed, true);
            app.SaveButton_Trans.Text = 'Save';
            app.SineTab = uitab(app.TabGroup3);
            app.SineTab.Title = 'Sine';
            app.CalculateButton_sine = uibutton(app.SineTab, 'push');
            app.CalculateButton_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sinePushed, true);
            app.CalculateButton_sine.Text = 'Calculate';
            app.SteadyStateSineBaseInputLabel = uilabel(app.SineTab);
            app.SteadyStateSineBaseInputLabel.Text = 'Steady-State Sine Base Input';
            app.SettoFirstModalFreqButton = uibutton(app.SineTab, 'push');
            app.SettoFirstModalFreqButton.Text = 'Set to First Modal Freq';
            app.AccelGEditFieldLabel = uilabel(app.SineTab);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.SineTab, 'numeric');
            app.FreqHzEditFieldLabel = uilabel(app.SineTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SineTab, 'numeric');
            app.HalfSineTab = uitab(app.TabGroup3);
            app.HalfSineTab.Title = 'Half-Sine';
            app.CalculateButton_halfsine = uibutton(app.HalfSineTab, 'push');
            app.CalculateButton_halfsine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_halfsinePushed, true);
            app.CalculateButton_halfsine.Text = 'Calculate';
            app.ResponsetoHalfSineBaseInputLabel = uilabel(app.HalfSineTab);
            app.ResponsetoHalfSineBaseInputLabel.Text = 'Response to Half-Sine Base Input';
            app.BaseInputAccelGEditFieldLabel = uilabel(app.HalfSineTab);
            app.BaseInputAccelGEditFieldLabel.Text = 'Base Input Accel (G)';
            app.BaseInputAccelGEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.DurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.PSDTab = uitab(app.TabGroup3);
            app.PSDTab.Title = 'PSD';
            app.TabGroup7 = uitabgroup(app.PSDTab);
            app.CalculateTab_4 = uitab(app.TabGroup7);
            app.CalculateTab_4.Title = 'Calculate';
            app.CalculateButton_psd = uibutton(app.CalculateTab_4, 'push');
            app.CalculateButton_psd.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_psdPushed, true);
            app.CalculateButton_psd.Text = 'Calculate';
            app.SaveTab_4 = uitab(app.TabGroup7);
            app.SaveTab_4.Title = 'Save';
            app.ArbitraryTab = uitab(app.TabGroup3);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.TabGroup6 = uitabgroup(app.ArbitraryTab);
            app.CalculateTab_3 = uitab(app.TabGroup6);
            app.CalculateTab_3.Title = 'Calculate';
            app.CalculateButton_arbitrary = uibutton(app.CalculateTab_3, 'push');
            app.CalculateButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_arbitraryPushed, true);
            app.CalculateButton_arbitrary.Text = 'Calculate';
            app.SaveTab_3 = uitab(app.TabGroup6);
            app.SaveTab_3.Title = 'Save';
            app.SaveButton_arbitrary = uibutton(app.SaveTab_3, 'push');
            app.SaveButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_arbitraryPushed, true);
            app.SaveButton_arbitrary.Text = 'Save';
            app.MDOFSRSTab = uitab(app.TabGroup3);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.CalculateButton_mdof_srs = uibutton(app.MDOFSRSTab, 'push');
            app.CalculateButton_mdof_srs.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_mdof_srsPushed, true);
            app.CalculateButton_mdof_srs.Text = 'Calculate';
            app.ResultsTextAreaLabel = uilabel(app.BaseExcitationTab_2);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.BaseExcitationTab_2);
            app.Image_base = uiimage(app.BaseExcitationTab_2);
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_system_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end