classdef two_dof_system_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwodofSystemUIFigure            matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SelectSystemTab                 matlab.ui.container.Tab
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        SelectButton_4                  matlab.ui.control.Button
        SelectButton_3                  matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        SelectButton_1                  matlab.ui.control.Button
        Image2_5                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        MassStiffnessTab                matlab.ui.container.Tab
        SelectInertiaMethodListBox      matlab.ui.control.ListBox
        SelectInertiaMethodListBoxLabel  matlab.ui.control.Label
        GoToModalButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Image_mk                        matlab.ui.control.Image
        ModalAnalysisTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CalculateTab_2                  matlab.ui.container.Tab
        NaturalFrequenciesTextArea      matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        MassNormalizedEigenvectorsTextArea  matlab.ui.control.TextArea
        MassNormalizedEigenvectorsTextAreaLabel  matlab.ui.control.Label
        StiffnessMatrixTextArea         matlab.ui.control.TextArea
        StiffnessMatrixTextAreaLabel    matlab.ui.control.Label
        MassMatrixTextArea              matlab.ui.control.TextArea
        MassMatrixTextAreaLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Image_modal                     matlab.ui.control.Image
        SaveTab_2                       matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        ParticipationFactorEditField    matlab.ui.control.EditField
        ParticipationFactorEditFieldLabel  matlab.ui.control.Label
        ModeShapesEditField             matlab.ui.control.EditField
        ModeShapesEditFieldLabel        matlab.ui.control.Label
        NaturalFrequencyEditField       matlab.ui.control.EditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        StiffnessEditField              matlab.ui.control.EditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        MassEditField                   matlab.ui.control.EditField
        MassEditFieldLabel              matlab.ui.control.Label
        OutputArrayNamesLabel           matlab.ui.control.Label
        DampingTab_2                    matlab.ui.container.Tab
        Mode2EditField                  matlab.ui.control.EditField
        Mode2EditFieldLabel             matlab.ui.control.Label
        Mode1EditField                  matlab.ui.control.EditField
        Mode1EditFieldLabel             matlab.ui.control.Label
        ListBoxQ                        matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        ListBoxUniform                  matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        SaveDampingButton               matlab.ui.control.Button
        DampingUnitLabel                matlab.ui.control.Label
        SelectTypesLabel                matlab.ui.control.Label
        BaseExcitationTab_2             matlab.ui.container.Tab
        Image_base                      matlab.ui.control.Image
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        TransTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        EndFreqHzEditField              matlab.ui.control.EditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.EditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateButton_Trans           matlab.ui.control.Button
        SaveTab                         matlab.ui.container.Tab
        SaveButton_Trans                matlab.ui.control.Button
        OutputArrayNameEditFieldTrans   matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        ExportArrayLabel                matlab.ui.control.Label
        AmplitudeTypeLabel              matlab.ui.control.Label
        ExportAmplitude                 matlab.ui.control.ListBox
        Label_4                         matlab.ui.control.Label
        ExportTrans                     matlab.ui.control.ListBox
        Label_3                         matlab.ui.control.Label
        SineTab                         matlab.ui.container.Tab
        FreqHzEditField                 matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        AccelGEditField                 matlab.ui.control.EditField
        AccelGEditFieldLabel            matlab.ui.control.Label
        SettoFirstModalFreqButton       matlab.ui.control.Button
        SteadyStateSineBaseInputLabel   matlab.ui.control.Label
        CalculateButton_sine            matlab.ui.control.Button
        HalfSineTab                     matlab.ui.container.Tab
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        BaseAccelGEditField             matlab.ui.control.EditField
        BaseAccelGEditFieldLabel        matlab.ui.control.Label
        ResponsetoHalfSineBaseInputLabel  matlab.ui.control.Label
        CalculateButton_halfsine        matlab.ui.control.Button
        PSDTab                          matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        CalculateTab_4                  matlab.ui.container.Tab
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        InputArrayNamePSD               matlab.ui.control.Label
        CalculateButton_psd             matlab.ui.control.Button
        SaveTab_4                       matlab.ui.container.Tab
        OutputArrayListBox              matlab.ui.control.ListBox
        OutputArrayListBoxLabel         matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        SaveButton_PSD                  matlab.ui.control.Button
        ArbitraryTab                    matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        CalculateTab_3                  matlab.ui.container.Tab
        SelectInputArrayListBox_arb     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_arb            matlab.ui.control.Button
        text1_2                         matlab.ui.control.Label
        listbox_method_arb              matlab.ui.control.ListBox
        ResponsetoarbitrarybaseinputLabel  matlab.ui.control.Label
        CalculateButton_arbitrary       matlab.ui.control.Button
        SaveTab_3                       matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SelectOutputListBox             matlab.ui.control.ListBox
        SelectOutputListBoxLabel        matlab.ui.control.Label
        SaveButton_arbitrary            matlab.ui.control.Button
        MDOFSRSTab                      matlab.ui.container.Tab
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        TabGroup8                       matlab.ui.container.TabGroup
        ReadSRSTab                      matlab.ui.container.Tab
        SelectInputArrayListBox_srs     matlab.ui.control.ListBox
        UpdateListButton_srs            matlab.ui.control.Button
        text1_3                         matlab.ui.control.Label
        listbox_method_srs              matlab.ui.control.ListBox
        CalculateButton_mdof_srs_read   matlab.ui.control.Button
        ManuallyEnterSRSTab             matlab.ui.container.Tab
        UITable_srs                     matlab.ui.control.Table
        NumberofCoordinatesListBox      matlab.ui.control.ListBox
        NumberofCoordinatesLabel        matlab.ui.control.Label
        CalculateButton_mdof_srs        matlab.ui.control.Button
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        two_dof_system_ahver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iflag      % Description
        damp1      %
        damp2      %
        dm         % 
        fn         %
        ModeShapes %
        MST        %
        mm         %
        pff        %
        iu         %
        mass       %
        stiffness  %
        omegan     %
        nJ         % 
        acc_trans      %
        power_trans    %
        rd_trans       %
        rd_power_trans %
        rdm2m1_trans   %    
        acc_response_psd  %
        a1_response_psd   %
        a2_response_psd   %
        rd1_response_psd  %
        rd2_response_psd  % 
        rd21_response_psd %   
        rd_response_psd   %
        THM    %
        method %
        a1s    % 
        a2s    % 
        rd1s   %
        rd2s   %
        rd21s  %  
        nc     %
    end

    methods (Access = private)

       function mdof_core(app) 
            Q=app.SpecificationQEditField.Value;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            num_fn=length(app.fn);      

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fig_num=1;
            
            md=4;
            t_string=sprintf('SRS Q=%g',Q);
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
            
            [~]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.fn(1)<app.THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  


            f=app.THM(:,1);
            a=app.THM(:,2);
            num_srs=length(app.THM(:,1));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            accel_abssum=zeros(num_fn,1);
              accel_srss=zeros(num_fn,1);
  
            pv_abssum=zeros(num_fn,1);
              pv_srss=zeros(num_fn,1);  

            rd_abssum=zeros(num_fn,1);
              rd_srss=zeros(num_fn,1);

             a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);

            slope=zeros(num_srs-1,1);

            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(f(i+1)/f(i));
            end

            for i=1:num_fn
  
                for k=1:(num_srs-1)
                    if(app.fn(i)>=f(k) && app.fn(i)<=f(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/f(k))^slope(k);
                        pv_srs(i)=a_srs(i)/app.omegan(i); 
                        rd_srs(i)=a_srs(i)/app.omegan(i)^2;    
                        break;
                    end    
                end
            end 
    
            for i=1:num_fn
   
                for j=1:num_fn
        
                    PFM=app.pff(j)*app.ModeShapes(i,j);
    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
  
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
          
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
  
                end
    
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
       
            end

            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end

            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   

            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   

            s2='dof    SRSS    Abssum' ;

            ss=sprintf('Acceleration (G)\n\n%s',s2);

            for i=1:num_fn
                s3=sprintf(' %d   %8.4g  %8.4g ',i,accel_srss(i),accel_abssum(i));            
                ss=sprintf('%s\n%s',ss,s3);
            end
 
            if(app.iu==1)
                s4='Pseudo Velocity (in/sec)';
            else
                s4='Pseudo Velocity (cm/sec)';    
            end
           
            s5='dof    SRSS    Abssum';
            ss=sprintf('%s\n\n%s\n\n%s',ss,s4,s5);   

            for i=1:num_fn
                s6=sprintf(' %d   %8.4g  %8.4g ',i,pv_srss(i),pv_abssum(i));
                ss=sprintf('%s\n%s',ss,s6);              
            end

            if(app.iu==1)
                s7='Relative Displacement (in)';
            else
                s7='Relative Displacement  (mm)';    
            end    
    
            s8='dof   SRSS    Abssum' ;
            ss=sprintf('%s\n\n%s\n\n%s',ss,s7,s8);  

            for i=1:num_fn
                s9=sprintf(' %d   %8.3g  %8.3g ',i,rd_srss(i),rd_abssum(i));
                ss=sprintf('%s\n%s',ss,s9);                
            end

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';
       end 

       function coordinates(app)
            app.nc=str2num(app.NumberofCoordinatesListBox.Value);

            for i=1:app.nc
                for j=1:2
                    d{i,j}='';
                end
            end
            app.UITable_srs.Data=d;
       end

       function update_arb(app)
            cell_array=update_time_history();
            try
                app.SelectInputArrayListBox_arb.Items=unique(sort(cell_array));
            catch
            end
       end        

       function update_srs(app)
            [cell_array]=update_shock_response_spectrum();
            try    
                app.SelectInputArrayListBox_srs.Items=unique(sort(cell_array));
            catch
            end
       end     

       function update_psd(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
       end     

       function array_method_arb(app)
            Items = char(app.listbox_method_arb.Items);
            im=app.listbox_method_arb.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_arb.Value=name;
            end

            app.listbox_method_arb.Value=app.listbox_method_arb.Items{1};
        end
 
        function array_method_srs(app)
            Items = char(app.listbox_method_srs.Items);
            im=app.listbox_method_srs.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);

               try
                    app.SelectInputArrayListBox_srs.Value=name;
               catch
                   warndlg('update srs failed');
                   return;
               end
            end

            app.listbox_method_srs.Value=app.listbox_method_srs.Items{1};
       end        

       function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox.Value=name;
            end

            app.listbox_method.Value=app.listbox_method.Items{1};
        end

        function check(app)
            try
                if(min(app.fn)<=0)
                    warndlg('Zero frequency detected. Run Modal Analysis Tab')
                    return;
                end
            catch
                warndlg('Run Natural Freuqency Tab')
                return;
            end
            
            try
                if(app.damp1>=0 && app.damp2>=0)
                else    
                    warndlg('Enter Damping at Damping Tab');
                    return;                
                end
            catch
                warndlg('Enter Damping at Damping Tab');
                return;
            end
            
        end        

        function ChangeDamping(app)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                app.DampingUnitLabel.Text = 'Q';
            else
                app.DampingUnitLabel.Text = 'Ratio';
            end
            
            if(contains(m,'Uniform'))
                app.Mode1EditFieldLabel.Text='Uniform';
                app.Mode2EditField.Visible = 'off';
                app.Mode2EditFieldLabel.Visible = 'off';                
            else
                app.Mode1EditFieldLabel.Text='Mode 1';                
                app.Mode2EditField.Visible = 'on';
                app.Mode2EditFieldLabel.Visible = 'on';                 
            end            
        end

        function set_table(app)
             
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            if(app.iu==1)
                sm='lbm';
                sk='lbf/in';
                sL='in';
                sJ='lbm in^2';
            else
                sm='kg';
                sk='N/m';
                sL='m';
                sJ='kg m^2';
            end    

            app.SelectInertiaMethodListBox.Visible='off';
            app.SelectInertiaMethodListBoxLabel.Visible='off';

            if(app.iflag==1)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
            end 
            if(app.iflag==2)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K1';
                ss{4,1}='K2';

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';  

                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
                ss{4,3}=sk;                
            end    
            if(app.iflag==3)
                ss{1,1}='M1';
                ss{2,1}='M2';
                ss{3,1}='K1';
                ss{4,1}='K2';
                ss{5,1}='K3';

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';  
                ss{5,2}='';               

                ss{1,3}=sm;
                ss{2,3}=sm;
                ss{3,3}=sk;
                ss{4,3}=sk; 
                ss{5,3}=sk;             
            end 
            if(app.iflag==4)

                app.SelectInertiaMethodListBox.Visible='on';
                app.SelectInertiaMethodListBoxLabel.Visible='on';

                Items = char(app.SelectInertiaMethodListBox.Items);
                im=app.SelectInertiaMethodListBox.Value;
                [app.nJ]=find_index(Items,im);              

                ss{1,1}='M';
                ss{2,1}='J';
                ss{3,1}='K1';
                ss{4,1}='K2';
                ss{5,1}='L1';
                ss{6,1}='L2';               

                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';              
                ss{4,2}='';    
                ss{5,2}='';              
                ss{6,2}='';                

                ss{1,3}=sm;
                ss{2,3}=sJ;
                ss{3,3}=sk;
                ss{4,3}=sk;      
                ss{5,3}=sL;
                ss{6,3}=sL; 

                if(app.nJ==2)
                    ss{2,1}='Radius';    
                    ss{2,3}=sL;
                end    
            end                 

            app.UITable.Data=ss; 
        end    
 
        function clear_modal(app)
            app.MassMatrixTextArea.Visible='off';
            app.StiffnessMatrixTextArea.Visible='off';
            app.MassNormalizedEigenvectorsTextArea.Visible='off';
            app.NaturalFrequenciesTextArea.Visible='off';
            app.MassMatrixTextAreaLabel.Visible='off';
            app.StiffnessMatrixTextAreaLabel.Visible='off';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='off';
            app.NaturalFrequenciesTextAreaLabel.Visible='off';            
        end    

        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
        end    

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
   

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear(app);            
            app.nJ=1;
            app.iflag=2;
            app.Image_mk.Position = [10 160 178 184];
            app.Image_mk.ImageSource = 'twodof_a.jpg';        
            app.Image_modal.Position = [10 160 178 184];
            app.Image_modal.ImageSource = 'twodof_a.jpg';  
            set_table(app);
            app.Mode1EditField.Value='10';
            ChangeDamping(app);
            update_psd(app);
            update_arb(app);
            update_srs(app);
            coordinates(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SelectButton_1
        function SelectButton_1Pushed(app, event)
            clear(app);
            clear_modal(app);
            app.iflag=1;
            app.Image_mk.Position = [180 170 203 174];
            app.Image_mk.ImageSource = 'semidefinite.jpg';  
            app.Image_modal.Position = [10 160 203 174];
            app.Image_modal.ImageSource = 'semidefinite.jpg';        
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            clear(app);
            clear_modal(app);
            app.iflag=2;
            app.Image_mk.Position = [200 160 178 184];
            app.Image_mk.ImageSource = 'twodof_a.jpg';   
            app.Image_modal.Position = [150 160 178 184];
            app.Image_modal.ImageSource = 'twodof_a.jpg';              
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            app.Image_base.ImageSource ='two_dof_std_base.jpg'; 
            app.Image_base.Position = [30 160 178 184];
            set_table(app);
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            clear(app);
            clear_modal(app);
            app.iflag=3;
            app.Image_mk.Position = [1 150 round(409*1.1) round(166*1.1)];     
            app.Image_mk.ImageSource = 'two_dof_alt_fixed.jpg';  
            app.Image_modal.Position = [1 130 round(409*1.2) round(166*1.2)];     
            app.Image_modal.ImageSource = 'two_dof_alt_fixed.jpg';
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            app.Image_base.ImageSource ='two_dof_alt_base.jpg'; 
            app.Image_base.Position = [30 160 178 184];           
            set_table(app);
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            clear(app);
            clear_modal(app);
            app.iflag=4;
            app.Image_mk.Position = [1 180 409 166];
            app.Image_mk.ImageSource = 'two_dof_system_c.jpg'; 
            app.Image_modal.Position = [1 150 round(409*0.9) round(166*0.9)];
            app.Image_modal.ImageSource = 'two_dof_system_c.jpg';
            app.Image_base.ImageSource ='two_dof_base_di.jpg';
            app.TabGroup.SelectedTab = app.MassStiffnessTab;
            set_table(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                if(contains(m,'Uniform'))
                     Q1=str2num(app.Mode1EditField.Value);
                     Q2=Q1;
                     app.damp1=1/(2*Q1);
                     app.damp2=app.damp1;
                else
                     Q1=str2num(app.Mode1EditField.Value);
                     Q2=str2num(app.Mode2EditField.Value);
                     app.damp1=1/(2*Q1);
                     app.damp2=1/(2*Q2);                
                end    
            else
                if(contains(m,'Uniform'))
                     app.damp1=str2num(app.Mode1EditField.Value);
                     app.damp2=app.damp1;
                     Q1=1/(2*app.damp1);
                     Q2=Q1;              
                else
                     app.damp1=str2num(app.Mode1EditField.Value);
                     app.damp2=str2num(app.Mode2EditField.Value);
                     Q1=1/(2*app.damp1);
                     Q2=1/(2*app.damp2);                   
                end                   
            end         
            
            app.dm=[app.damp1 app.damp2];
            
            fprintf('\n Damping Ratios \n Mode 1       2        \n');
            fprintf('    %6.3g  %6.3g  \n',app.damp1,app.damp2);

            msgbox('Damping saved.  Go to Base Excitation');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clear(app);

            app.MassMatrixTextAreaLabel.Visible='on';
            app.MassMatrixTextArea.Visible='on';
            app.StiffnessMatrixTextArea.Visible='on';
            app.MassNormalizedEigenvectorsTextArea.Visible='on';
            app.NaturalFrequenciesTextArea.Visible='on';

            app.MassMatrixTextAreaLabel.Visible='on';
            app.StiffnessMatrixTextAreaLabel.Visible='on';
            app.MassNormalizedEigenvectorsTextAreaLabel.Visible='on';
            app.NaturalFrequenciesTextAreaLabel.Visible='on';

            A=char(app.UITable.Data);

            sz=size(A);
            N=round(sz(1)/3);

            app.iflag
                      
            if(app.iflag==1)
                m1=str2num(A(N+1,:));
                m2=str2num(A(N+2,:));
                k=str2num(A(N+3,:));
                app.stiffness=[ k -k ; -k k];
            end    
            if(app.iflag==2)
                m1=str2num(A(N+1,:));
                m2=str2num(A(N+2,:));
                k1=str2num(A(N+3,:));
                k2=str2num(A(N+4,:));      
                app.stiffness=[ (k1+k2) -k2 ; -k2 k2];             
            end   
            if(app.iflag==3)
                m1=str2num(A(N+1,:));
                m2=str2num(A(N+2,:));
                k1=str2num(A(N+3,:));
                k2=str2num(A(N+4,:));  
                k3=str2num(A(N+5,:));    
                app.stiffness=[(k1+k2) -k2; -k2 (k2+k3)];       
            end   
            if(app.iflag==4)
                m1=str2num(A(N+1,:));

                if(app.nJ==1)
                    m2=str2num(A(N+2,:));
                else
                    r=str2num(A(N+2,:));
                    m2=m1*r^2;
                end
                
                k1=str2num(A(N+3,:));
                k2=str2num(A(N+4,:));  
                L1=str2num(A(N+5,:));
                L2=str2num(A(N+6,:)); 
                app.stiffness(1,1)=k1+k2;
                app.stiffness(1,2)=-k1*L1+k2*L2;
                app.stiffness(2,2)=k1*L1^2+k2*L2^2;
                app.stiffness(2,1)=app.stiffness(1,2);             
            end               

            app.mass=[m1 0; 0 m2 ]; 
                
            if(app.iu==1)
                app.mass=app.mass/386.;
            end
 
            %%%      
            
            [app.fn,~,app.ModeShapes,app.MST]=Generalized_Eigen(app.stiffness,app.mass,2);
            
            app.omegan=2*pi*app.fn;  
            app.MST=app.ModeShapes';           

            %
            dof=length(app.fn);
            %
            v=ones(dof,1)';

            if(app.iu==1)
                s1='Mass Matrix (lbf sec^2/in)';
            else
                s1='Mass Matrix (kg)';    
            end
            app.MassMatrixTextAreaLabel.Text=s1;

            m=app.mass;
            ss_m=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',m(1,1),m(1,2),m(2,1),m(2,2));
            
            k=app.stiffness;
            if(app.iu==1)
                s2='Stiffness Matrix (lbf/in)';
            else
                s2='Stiffness Matrix (N/m)';    
            end
            app.StiffnessMatrixTextAreaLabel.Text=s2; 
            ss_k=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',k(1,1),k(1,2),k(2,1),k(2,2));

            e=app.ModeShapes;
            ss_e=sprintf('%8.4g\t  %8.4g  \n%8.4g\t  %8.4g',e(1,1),e(1,2),e(2,1),e(2,2));
            app.MassNormalizedEigenvectorsTextArea.Value=ss_e;


            if(app.iflag<=3)
                ss_f='            Natural      Participation    Effective Modal';
                
                if(app.iu==1)
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (lbm)';
                else
                    ss_ff='Mode   Freq (Hz)        Factor          Mass (kg)';    
                end    
            else
                ss_f='            Natural ';
                ss_ff='Mode   Freq (Hz) ';
 
            end

            ss_f=sprintf('%s\n%s\n',ss_f,ss_ff);
            
            %
            LM=app.MST*app.mass*v';
            pf=LM;
            sum=0;
            %    
            mmm=app.MST*app.mass*app.ModeShapes;   
            %
            
            app.pff=zeros(dof,1);
            emm=zeros(dof,1);
            
            app.fn(abs(app.fn)<1.0e-05) = 0;
            
            for i=1:dof
                    app.pff(i)=pf(i)/mmm(i,i);
                    emm(i)=pf(i)^2/mmm(i,i);
                    sum=sum+emm(i); 

                    if(app.iflag<=3)
                        if(app.iu==1)
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i)*386);           
                        else
                            out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\t\t   %8.4g\t\t %8.4g\n',i,app.fn(i),app.pff(i),emm(i));            
                        end
                    else
                        out1 = sprintf('  %d\t  %8.4g\t\t %8.4g\n',i,app.fn(i));                   
                    end

                    ss_f=sprintf('%s\n%s',ss_f,out1);
            end
            if(app.iu==1)
                out1=sprintf('\n modal mass sum = %8.4g lbm \n',sum*386);
            else
                out1=sprintf('\n modal mass sum = %8.4g kg \n',sum);    
            end

            if(app.iflag<=3)
                ss_fn=sprintf('%s\n%s\n',ss_f,out1);
            else
                ss_fn=ss_f;
            end

            app.MassMatrixTextArea.Value=ss_m;
            app.StiffnessMatrixTextArea.Value=ss_k;
            app.NaturalFrequenciesTextArea.Value=ss_fn; 

            if(app.iflag==4)

                if(app.iu==1)
                    xlab='Length (in)';
                else
                    xlab='Length (m)';    
                end
                
                x(1)=-L1;
                x(2)=0;
                x(3)=L2;
                
                alpha=app.ModeShapes(2,1);
                beta=app.ModeShapes(2,2);
                
                
                d1(1)=-L1*tan(alpha);
                d1(2)=0;
                d1(3)=L2*tan(alpha);
                
                d2(1)=-L1*tan(beta);
                d2(2)=0;
                d2(3)=L2*tan(beta);
                
                d1=d1+app.ModeShapes(1,1);
                d2=d2+app.ModeShapes(1,2);
                
                dn=zeros(3,1);
                
                xz=0;
                dz=0;
                
                h1=figure(1);
                plot(xz,dz,'bo',x,dn,'b',x,d1,'r',x,-d1,'r');
                out1=sprintf('First Mode Shape  %6.3g Hz  ',app.fn(1));
                title(out1);
                ylabel('Unscaled Displacement');
                xlabel(xlab);
                grid on;
                
                h2=figure(2);
                plot(xz,dz,'bo',x,dn,'b',x,d2,'r',x,-d2,'r');
                out1=sprintf('Second Mode Shape  %6.3g Hz  ',app.fn(2));
                title(out1);
                ylabel('Unscaled Displacement');
                xlabel(xlab);
                grid on;

            end      

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            name_mass=strtrim(app.MassEditField.Value);
            name_stiffness=strtrim(app.StiffnessEditField.Value);
            name_two_dof_fn=strtrim(app.NaturalFrequencyEditField.Value);
            name_ModeShapes=strtrim(app.ModeShapesEditField.Value);
            name_PF=strtrim(app.ParticipationFactorEditField.Value);

            try
                assignin('base', name_mass, app.mass);
                assignin('base', name_stiffness, app.stiffness);
                assignin('base', name_two_dof_fn, app.fn);
                assignin('base', name_ModeShapes, app.ModeShapes);
                assignin('base', name_PF', app.pff);
                msgbox('Arrays Saved');           
            catch
                warndlg('Save failed');
            end
        end

        % Button pushed function: CalculateButton_mdof_srs
        function CalculateButton_mdof_srsPushed(app, event)
            
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            A=char(app.UITable_srs.Data);

            N=app.nc;

            f=zeros(N,1);
            a=zeros(N,1);

            for i=1:N
                f(i)=str2num(A(i,:));
                j=N+i;
                a(i)=str2num(A(j,:));                
            end 
                      
            app.THM=[f a];

            mdof_core(app);

        end

        % Button pushed function: CalculateButton_Trans
        function CalculateButton_TransPushed(app, event)
            
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            fig_num=1;          
            unit=app.iu;

            fmin=str2num(app.StartFreqHzEditField.Value);
            fmax=str2num(app.EndFreqHzEditField.Value);
              
            if(fmin<=0.01)
                fmin=0.01;
            end       
            
            app.omegan=2*pi*app.fn;        
            app.MST=app.ModeShapes';       

            %
            sz=size(app.ModeShapes);
            dof=(sz(1));
            %
            N=96;
            %
            [freq,omega,np]=frequencies_octave_spaced(fmin,fmax,N);
            
            om2=omega.^2;
            omn2=app.omegan.^2;
            %
            two_damp_omegan=zeros(2,1);
            %

            for i=1:dof 
                two_damp_omegan(i)=2*app.dm(i)*app.omegan(i);
            end
            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %   
                 rd=zeros(np,dof);
                 rdm2m1=zeros(np,1);
                acc=zeros(np,dof);
            %
                try
                    mmm=[app.mass(1,1); app.mass(2,2)];
                catch
                    warndlg('mass error');
                    return;
                end

                Y=-app.MST*mmm;
            %
                for i=1:np 
            %
                     n=zeros(2,1);
            %
                    for j=1:dof
                       A=Y(j);
                       den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                       n(j)=A/den;
                    end
                    na=-om2(i)*n;
            %
                     rd(i,:)=app.ModeShapes*n;
                    acc(i,:)=app.ModeShapes*na;
                    acc(i,:)=acc(i,:)+1;  
            %
                    rdm2m1(i,1)=rd(i,2)-rd(i,1);
            %
                end 
            %
                 rd=abs(rd);
                 rdm2m1=abs(rdm2m1);
                acc=abs(acc);

            %
                if(unit==1)
                  app.rd_trans=[freq 386*rd];
                  app.rdm2m1_trans=[freq 386*rdm2m1];
                else
                  app.rd_trans=[freq 9.81*rd];   
                  app.rdm2m1_trans=[freq 9.81*rdm2m1];      
                end

                size(app.rdm2m1_trans)
          
            %
                ppp1=[freq acc(:,1)];
                ppp2=[freq acc(:,2)];
                
                leg1='Mass 1';
                leg2='Mass 2';
                
                t_string=' Accel Transmissibility Magnitude';
                
                x_label='Frequency (Hz)';
                y_label='Trans (G/G)';
                
                md=5;
                
                [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %
                md=5;
                x_label='Frequency (Hz)';
                if(unit==1)
                    y_label='Trans (in/G)';
                else
                    y_label='Trans (m/G)';       
                end
                
                ppp1=[freq  app.rd_trans(:,2)];
                ppp2=[freq  app.rd_trans(:,3)];
                ppp3=[freq  app.rdm2m1_trans(:,2)];
                
                leg1='mass 1 - base';
                leg2='mass 2 - base';
                leg3='mass 2 - mass 1';  
                
                t_string=' Rel Disp Transmissibility Magnitude';
                
                [fig_num,h2]=plot_loglog_function_md_three_h2(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);
                
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                app.acc_trans(:,1)=freq;
                app.acc_trans(:,2)=acc(:,1);
                app.acc_trans(:,3)=acc(:,2);
            %
                app.power_trans(:,1)=freq; 
            
            %
                clear length;
                nnn=length(freq);
            %
                for i=1:nnn

                    for j=2:3
                       app.power_trans(i,j)=(app.acc_trans(i,j))^2;      
                    end
                end
            
        end

        % Button pushed function: CalculateButton_sine
        function CalculateButton_sinePushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            unit=app.iu;
                         
            %
            amp=str2num(app.AccelGEditField.Value); 
            freq=str2num(app.FreqHzEditField.Value);  
            
            app.omegan=2*pi*app.fn;        
                    
            %
            sz=size(app.ModeShapes);
            dof=(sz(1));
            %
            omega=2*pi*freq;
            om2=omega.^2;
            %
            omn2=app.omegan.^2;

            two_damp_omegan = 2 * app.dm .* app.omegan;
            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %
            np=1;   
            %
            mmm=[app.mass(1,1); app.mass(2,2)];
            Y=-app.MST*mmm;
            %
            rd=zeros(np,dof);
            acc=zeros(np,dof);
            %
            for i=1:np 
            %
                n=zeros(2,1);
            %
                    for j=1:dof
                       den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                       n(j)=Y(j)/den;
                    end
                    na=-om2(i)*n;
            %
                     rd(i,:)=app.ModeShapes*n;
                    acc(i,:)=app.ModeShapes*na;
                    acc(i,:)=acc(i,:)+1;  
            %
            end 

            if(unit==1)
                app.rd_trans=386*rd;
            else
                app.rd_trans=9.81*rd;   
            end
                
            app.acc_trans=acc;
            
            a1=amp*app.acc_trans(1,1);
            a2=amp*app.acc_trans(1,2);
            
            rd1=amp*app.rd_trans(1,1);
            rd2=amp*app.rd_trans(1,2);
            
            aa=[ a1 a2 ];
            aa=abs(aa);
            amax=max(abs(aa));
            %
            for i=1:2
                if(abs(aa(i))< (amax/10000) || abs(aa(i))< 1.0e-10)
                    aa(i)=0;
                end
            end
            %
            
            s1='Acceleration Response ';
            s2=sprintf('  Mass 1:  %8.4g G',aa(1));
            s3=sprintf('  Mass 2:  %8.4g G',aa(2));
            
            %
            rr=[ rd1 rd2 ];
            %
            if(unit==1)
               dd='in'; 
            else
               dd='mm'; 
               rr=rr*1000;
            end
            %
            rd21=rr(:,2)-rr(:,1);
            rr=abs(rr);
            rmax=max(abs(rr));
            %
            for i=1:2 
                if(abs(rr(i))< (rmax/10000) || abs(rr(i))< 1.0e-10)
                    rr(i)=0;
                end
            end   
       
            s4='Relative Displacement ';
            s5=sprintf('  Mass 1:  %8.4g %s',rr(1),dd);
            s6=sprintf('  Mass 2:  %8.4g %s',rr(2),dd);
            s7=sprintf('  Mass 2 - Mass 1:  %8.4g %s',abs(rd21),dd);

            ss=sprintf('%s\n\n %s\n %s\n\n %s\n\n %s\n %s\n %s\n %s',s1,s2,s3,s4,s5,s6,s7);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

        end

        % Button pushed function: CalculateButton_halfsine
        function CalculateButton_halfsinePushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            fig_num=1;
                     
            Q=app.ModeShapes;
      
            A=str2num(app.BaseAccelGEditField.Value);
            T=str2num(app.DurationsecEditField.Value);

            if(app.iu==1)
                A=A*386;
            else
                A=A*9.81;
            end

            dur1=2*T;
            dur2=10/min(app.fn);
            dur=max([dur1 dur2]);

            sr1=20/T;
            sr2=20*max(app.fn);
            sr=max([sr1 sr2]);

            dt=1/sr;

            nt=round(dur/dt);

            omega=pi/T;
            
            den=zeros(2,1);
            U1=zeros(2,1);
            U2=zeros(2,1);
            V1=zeros(2,1);
            V2=zeros(2,1);
            P=zeros(2,1);
            omegad=zeros(2,1);
            domegan=zeros(2,1);
            omn2=zeros(2,1);

            rd=zeros(nt,2);
            rv=zeros(nt,2);
            ra=zeros(nt,2);
            abase=zeros(nt,1);

            om2=omega^2;
            
            for i=1:2
                omegad(i)=app.omegan(i)*sqrt(1-app.dm(i)^2);
                domegan(i)=app.dm(i)*app.omegan(i);
                omn2(i)=app.omegan(i)^2;
                den(i)=( (om2-omn2(i))^2 + (2*app.dm(i)*omega*app.omegan(i))^2);
                U1(i)=2*app.dm(i)*omega*app.omegan(i);
                U2(i)=om2-omn2(i);
                V1(i)=2*app.dm(i)*app.omegan(i)*omegad(i);
                V2(i)=om2-omn2(i)*(1-2*(app.dm(i))^2);
                P(i)=omega/omegad(i);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Transition points
            %
            t=T;
            %
            eee=exp(-domegan*t); 
            %
            arg=omega*t;
            %
            argd=omegad*t;
            %
            c1=cos(arg);
            s1=sin(arg);
            cd=cos(argd);
            sd=sin(argd);
            %

            mmm=[app.mass(1,1); app.mass(2,2)];
            An=app.MST*mmm*A;
            %
            deee=zeros(2,1);
            Tn=zeros(2,1);
            Tnv=zeros(2,1);
            W=zeros(2,1);
            
            for j=1:2
            %               
                term1=U1(j)*c1 + U2(j)*s1;
                term2=V1(j)*cd(j)+V2(j)*sd(j);
                dterm1=    omega*( -U1(j)*s1 + U2(j)*c1 );
                dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                deee(j)=-app.dm(j)*app.omegan(j)*eee(j);
            %
                Tn(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
            %
                Tnv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
            %
                W(j)= ( Tnv(j) +   app.dm(j)*app.omegan(j)*Tn(j) )/omegad(j);
            %
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            tt=zeros(nt,1);
            
            a=zeros(nt,2);
            n=zeros(2,1);
            nv=zeros(2,1);
            na=zeros(2,1);        
            
            for i=1:nt
                t=dt*(i-1);
                tt(i)=t;
                %        
                eee=exp(-domegan*t); 
                %
                arg=omega*t;
                %
                argd=omegad*t;
                %
                c1=cos(arg);
                s1=sin(arg);
                cd=cos(argd);
                sd=sin(argd);
                %
        
                for j=1:2
                %
                    if(t<=T)
                        abase(i)=A*s1;
                        term1=U1(j)*c1 + U2(j)*s1;
                        term2=V1(j)*cd(j)+V2(j)*sd(j);
                        dterm1=    omega*( -U1(j)*s1 + U2(j)*c1 );
                        dterm2=omegad(j)*( -V1(j)*sd(j) + V2(j)*cd(j) );
                        deee(j)=-damp(j)*app.omegan(j)*eee(j);
    
                        n(j)=An(j)*(term1-P(j)*eee(j)*term2)/den(j);
                        nv(j)=An(j)*(dterm1 -P(j)*( deee(j)*term2 +eee(j)*dterm2))/den(j);          
    
                    else
                        abase(i)=0;
                        ts=t-T;
    
                        eee(j)=exp(-domegan(j)*ts); 
                        deee(j)=-app.dm(j)*app.omegan(j)*eee(j);                
    
                        arg=omega*ts;
    
                        argd=omegad*ts;
    
                        c1=cos(arg);
                        s1=sin(arg);
                        cd=cos(argd);
                        sd=sin(argd);
    
                         n(j)=    eee(j)*( Tn(j)*cd(j)  +W(j)*sd(j) );
                        nv(j)=   deee(j)*n(j)...
                          +omegad(j)*eee(j)*( -Tn(j)*sd(j)  +W(j)*cd(j) );           
                    end
    
                    na(j)= -2*app.dm(j)*app.omegan(j)*nv(j) -omn2(j)*n(j) -An(j)*abase(i)/A;
    
                end
    
                rd(i,:)=Q*n;
                rv(i,:)=Q*nv;
                ra(i,:)=Q*na;  
    
                a(i,:)=ra(i,:);        
                a(i,:)=a(i,:)+abase(i);
    
            end
            %
            if(app.iu==1)
               a=a/386;  
               abase=abase/386;
            else
                a=a/9.81;
                abase=abase/9.81;
                rd=rd*1000;
            end
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,rd(:,1),tt,rd(:,2));
            yl = ylim;
            ym=max(abs(yl));
            ylim([-ym,ym]);
            grid on;
            legend ('Mass 1','Mass 2'); 
            xlabel('Time(sec)');
            %
            out1=sprintf('Relative Displacement Response to Half-Sine Pulse Base Input');        
            title(out1);
            if(app.iu==1)
                ylabel('Rel Disp(in)');
            else
                ylabel('Rel Disp(mm)');    
            end
    
            figure(fig_num);
     
            plot(tt,abase,'k');
            hold on;
            plot(tt,a(:,1),'b'),
            plot(tt,a(:,2),'r');
            hold off;
            
            yl = ylim;
            ym=max(abs(yl));
            ylim([-ym,ym]);
            
            grid on;
            xlabel('Time(sec)');
    
            out1=sprintf('Acceleration Response to Half-Sine Pulse Base Input');   
            title(out1);
    
            ylabel('Accel(G)');
            legend ('Base Input','Mass 1','Mass 2');         
    
            a1=a(:,1);
            a2=a(:,2);
            rd1=rd(:,1);
            rd2=rd(:,2);
    
            if(max(abs(a1))<1.0e-09 )
                a1=0.;
            end
            if(max(abs(a2))<1.0e-09 )
                a2=0.;
            end 
    
            if(max(abs(rd1))<1.0e-09 )
                rd1=0.;
            end  
            if(max(abs(rd2))<1.0e-09 )
                rd2=0.;
            end   
    
            s1='Acceleration Response (G)';
            s2='              max       min  ';
            s3=sprintf('  Mass 1:  %7.4g   %7.4g \n',max(a1),min(a1));
            s4=sprintf('  Mass 2:  %7.4g   %7.4g \n',max(a2),min(a2));
    
            if(app.iu==1)
                s5='Relative Displacement (inch)';
            else
                s5='Relative Displacement (mm) ';    
            end
            s6='              max      min  ';
            s7=sprintf('  Mass 1:  %7.4g   %7.4g \n',max(rd1),min(rd1));
            s8=sprintf('  Mass 2:  %7.4g   %7.4g \n',max(rd2),min(rd2));
            rd21=rd2-rd1;
            s9=sprintf('\n Mass 2-Mass 1:  %7.4g   %7.4g \n',max(rd21),min(rd21));

            ss=sprintf('%s\n\n %s\n %s\n\n %s\n\n %s\n %s\n %s\n %s\n %s\n %s',s1,s2,s3,s4,s5,s6,s7,s8,s9);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';            

        end

        % Button pushed function: CalculateButton_psd
        function CalculateButton_psdPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            fig_num=1; 
            
            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            
            if(min(app.THM(:,1))<0 || min(app.THM(:,2))<0)
                warndlg('The input PSD must not contain any negative numbers');
                return;
            end    

            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end
            
            sz=size(app.ModeShapes);
            dof=sz(1);
            %
            app.THM=fix_size(app.THM);
            %
            sz=size(app.THM);
            n=sz(1);
            %
            fstart=app.THM(1,1);
            fend=app.THM(n,1);
            %
            if(fstart<=0)
                warndlg('Error: starting frequency must be >0 ');
                return;
            end
            %
            N=48;
            %
            noct=log(fend/fstart)/log(2);
            np=floor(noct*N);
            %
            freq=zeros(np,1);
            %
            R=2^(1/N);
            %
            freq(1)=fstart;
            for i=2:np
                freq(i)=freq(i-1)*R;
            end
            freq(np)=fend;
            %
            N=np;
            %
            freq=fix_size(freq);
            %
            omega=2*pi*freq;
            om2=omega.^2;
            %
            omn2=app.omegan.^2;
            %
            two_damp_omegan=zeros(dof,1);
            %
            for i=1:dof 
                two_damp_omegan(i)=2*app.dm(i)*app.omegan(i);
            end
            %
            %  Being main loop ********************************************************
            %
            %  np=number of excitation frequencies
            %
            rd=zeros(N,dof);
            acc=zeros(N,dof);

            mmm=[app.mass(1,1); app.mass(2,2)];
            Y=-app.MST*mmm;

            for i=1:N 

                 n=zeros(dof,1);

                for j=1:dof
                    A=Y(j);
                    den= (omn2(j)-om2(i)) + (1i)*(two_damp_omegan(j)*omega(i));
                    n(j)=A/den;
                end
                na=-om2(i)*n;

               rd(i,:)=app.ModeShapes*n;
               acc(i,:)=app.ModeShapes*na;
               acc(i,:)=acc(i,:)+1;  

            end 
            %
            acc=abs(acc);
            %
            if(app.iu==1)
                app.rd_trans=386*rd;
            else
                app.rd_trans=9.81*rd;   
            end
            %
            rd21_trans=abs(app.rd_trans(:,2)-app.rd_trans(:,1)); 
            app.rd_trans=abs(app.rd_trans);
            %
            app.acc_trans=acc; 
            %
            power_acc=zeros(N,2);
            power_rd=zeros(N,2);
            power_rd21=zeros(N,2);            
            %
            for i=1:N
                for j=1:2
                    power_acc(i,j)=acc(i,j)^2;
                    power_rd(i,j)=app.rd_trans(i,j)^2;
                    power_rd21(i)=rd21_trans(i)^2;
                end
                power_rd21(i)=rd21_trans(i)^2;    
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            y=double(app.THM(:,2));
            x=double(app.THM(:,1)); 
            %
            xL=log10(x);
            yL=log10(y);
            xiL=log10(freq);
            %
            yiL = interp1(xL,yL,xiL);
            %
            clear xi;
            clear yi;
            clear freq;
            clear length;
            M=length(yiL);
            %
            xi=zeros(M,1);
            yi=zeros(M,1);
            for i=1:M
                xi(i)=10^xiL(i);
                yi(i)=10^yiL(i);
            end
            %
            freq=xi;
            %
            P=min([ length(power_acc(:,1)) length(yi)]);
            %
            app.acc_response_psd=zeros(P,2);
            app.rd_response_psd=zeros(P,2);
            app.rd21_response_psd=zeros(P,1);
            
            for i=1:P
                for j=1:2
                    app.acc_response_psd(i,j)=power_acc(i,j)*yi(i);
                    app.rd_response_psd(i,j)= power_rd(i,j)*yi(i);
                end
                app.rd21_response_psd(i)= power_rd21(i)*yi(i);
            end
            %
            temp=freq;
            clear freq;
            freq=temp(1:P);
            %
            [~,accel_rms_1]=calculate_PSD_slopes_no( freq,app.acc_response_psd(:,1)  );
            [~,accel_rms_2]=calculate_PSD_slopes_no( freq,app.acc_response_psd(:,2)  );
            %
            [~,disp_rms_1]=calculate_PSD_slopes_no(  freq,app.rd_response_psd(:,1)   );
            [~,disp_rms_2]=calculate_PSD_slopes_no(  freq,app.rd_response_psd(:,2)   );
            [~,disp_rms_21]=calculate_PSD_slopes_no(  freq,app.rd21_response_psd   );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                dd='in'; 
            else
                dd='mm';
                disp_rms_1=disp_rms_1*1000;
                disp_rms_2=disp_rms_2*1000; 
                disp_rms_21=disp_rms_21*1000;    
            end

            s1='Acceleration Response ';
            s2=sprintf(' Mass 1:  %8.4g GRMS ',accel_rms_1);
            s3=sprintf(' Mass 2:  %8.4g GRMS ',accel_rms_2);
            
            s4='Relative Displacement Response ';
            s5=sprintf(' Mass 1:  %8.4g %s RMS ',disp_rms_1,dd);
            s6=sprintf(' Mass 2:  %8.4g %s RMS ',disp_rms_2,dd);
           
            s7=sprintf('\n Mass 2-Mass 1: \n  %8.4g %s RMS',disp_rms_21,dd);
            %
            if(app.iu==1)
                ylab_rd=sprintf('Rel Disp (inch^2/Hz)');
            else
                ylab_rd=sprintf('Rel Disp (mm^2/Hz)');
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            [~,input_rms]=calculate_PSD_slopes_no( app.THM(:,1),app.THM(:,2)  );
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            freq=fix_size(freq);
            %
            t_string='Acceleration Response PSD ';
            leg1=sprintf('Base Input %6.3g GRMS',input_rms);
            leg2=sprintf('Mass 1 Response %6.3g GRMS',accel_rms_1);
            leg3=sprintf('Mass 2 Response %6.3g GRMS',accel_rms_2);    
            
            ppp1=app.THM;
            ppp2=[freq app.acc_response_psd(:,1)];
            ppp3=[freq app.acc_response_psd(:,2)];
           
            app.a1_response_psd=ppp2;
            app.a2_response_psd=ppp3;
            
            md=5;
            x_label='Frequency (Hz)';
            y_label='Accel (G^2/Hz)';
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);

            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);

            if(app.iu==2)
                app.rd_response_psd=app.rd_response_psd*1.0e+06;
                app.rd21_response_psd=app.rd21_response_psd*1.0e+06;
            end

            app.rd21_response_psd=fix_size(app.rd21_response_psd);

            ppp1=[freq app.rd_response_psd(:,1)];
            ppp2=[freq app.rd_response_psd(:,2)];
            ppp3=[freq app.rd21_response_psd];
            
            app.rd1_response_psd=ppp1;
            app.rd2_response_psd=ppp2;            
            app.rd21_response_psd=ppp3;

            t_string='Relative Displacement Response PSD';
            
            leg1=sprintf('mass 1 - base    %6.3g %s RMS',disp_rms_1,dd);
            leg2=sprintf('mass 2 - base    %6.3g %s RMS',disp_rms_2,dd);
            leg3=sprintf('mass 2 - mass 1  %6.3g %s RMS',disp_rms_21,dd);
        
            y_label=ylab_rd;
            md=6;
            
            [~]=plot_loglog_function_md_three(fig_num,x_label,...
               y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,fmin,fmax,md);
           
            ss=sprintf('%s\n\n %s\n %s\n\n %s\n\n %s\n %s\n %s\n %s\n',s1,s2,s3,s4,s5,s6,s7);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';   

        end

        % Button pushed function: CalculateButton_arbitrary
        function CalculateButton_arbitraryPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            fig_num=1;

            try
                FS=app.SelectInputArrayListBox_arb.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end

            t=app.THM(:,1);
            base=app.THM(:,2);
        
            dt=mean(diff(t));

            dtmin=min(diff(t));
            dtmax=max(diff(t));
    
            if(((dtmax-dtmin)/dt)>0.015)
                warndlg('Time step is not constant.');
                return;
            end

            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,base);
            data1=[t base];
            grid on;
            xlabel('Time(sec)');
            ylabel('Accel(G)');
            title('Base Input ');

            Q=app.ModeShapes;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %   Digital Recursive Filtering Relationship 
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            clear temp;
            temp=base;
            clear base;
            ntt=length(temp)+round((1.5/app.fn(1))/dt);
            base=zeros(ntt,1);
            base(1:length(temp))=temp;

            clear t;
            t=app.THM(1,1)+linspace(0,(ntt-1)*dt,ntt);

            nt=ntt;

            rd=zeros(nt,2);
            ra=zeros(nt,2);

            x=zeros(nt,2);
            a=zeros(nt,2);
    
            acc=zeros(nt,2);

            mmm=[app.mass(1,1); app.mass(2,2)];
            yy=-app.MST*mmm*base';
    
            for j=1:2

                omegad=app.omegan(j)*sqrt(1.-(app.dm(j)^2));
  
                cosd=cos(omegad*dt);
                sind=sin(omegad*dt);

                domegadt=app.dm(j)*app.omegan(j)*dt;

                eee1=exp(-domegadt);
                eee2=exp(-2.*domegadt);

                ecosd=eee1*cosd;
                esind=eee1*sind; 

                a1= 2.*ecosd;
                a2=-eee2;    

                omeganT=app.omegan(j)*dt;

                phi=2*(app.dm(j))^2-1;
                DD1=(app.omegan(j)/omegad)*phi;
                DD2=2*DD1;
    
                df1=2*app.dm(j)*(ecosd-1) +DD1*esind +omeganT;
                df2=-2*omeganT*ecosd +2*app.dm(j)*(1-eee2) -DD2*esind;
                df3=(2*app.dm(j)+omeganT)*eee2 +(DD1*esind-2*app.dm(j)*ecosd);

                MD=app.omegan(j)^3*dt;
                df1=df1/MD;
                df2=df2/MD;
                df3=df3/MD;

                af1=esind/(omegad*dt);
                af2=-2*af1;
                af3=af1;

                yj=yy(j,:);
  
                %   Relative Acceleration Response
 
                forward=[ af1,  af2,  af3 ];    
                back   =[     1, -a1, -a2 ];    
  
                a(:,j)=filter(forward,back,yj);

               %   Relative Displacement Response   
               
               rd_forward=[ df1,  df2,  df3 ];    
               rd_back   =[     1, -a1, -a2 ];     
  
               x(:,j)=filter(rd_forward,rd_back,yj);

            end

            for i=1:nt 
                rd(i,:)=Q*x(i,:)';
                ra(i,:)=Q*a(i,:)';  
                acc(i,:)=ra(i,:);
            end

            for i=1:2
                acc(:,i)=acc(:,i)+base;
            end

            clear a;
            a=acc;

            if(app.iu==1)
                rd=rd*386;
            else
                rd=rd*9.81*1000;     
            end

            figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,rd(:,1),t,rd(:,2));
            grid on; 
            xlabel('Time(sec)');

            out1=sprintf('Relative Displacement Response to Base Input');
       
            title(out1);
            if(app.iu==1)
                ylabel('Rel Disp(in)');
            else
                ylabel('Rel Disp(mm)');    
            end  
            legend('Mass 1','Mass 2');     
        
            figure(fig_num);
            fig_num=fig_num+1;
            rd21=rd(:,2)-rd(:,1);
            plot(t,rd21);
            grid on; 
            xlabel('Time(sec)');

            out1=sprintf('Relative Displacement Mass 2 - Mass 1');
       
            title(out1);
            if(app.iu==1)
                ylabel('Rel Disp(in)');
            else
                ylabel('Rel Disp(mm)');    
            end

            figure(fig_num);
            fig_num=fig_num+1;    
            plot(t,a(:,1),t,a(:,2));
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response to Base Input');   
            title(out1);

            ylabel('Accel(G)');
            legend('Mass 1','Mass 2');
        
            t=fix_size(t);
        
            aone=a(:,1);
            aone=fix_size(aone);
            atwo=a(:,2);
            atwo=fix_size(atwo);        

            figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,aone);
            data2=[t aone];
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response Mass 1');   
            title(out1);

            ylabel('Accel(G)');

            figure(fig_num);
            fig_num=fig_num+1;     
            plot(t,atwo);
            data3=[t atwo];
            grid on;
            xlabel('Time(sec)');

            out1=sprintf('Acceleration Response Mass 2');   
            title(out1);

            ylabel('Accel(G)');
  
            a1=a(:,1);
            a2=a(:,2);

            rd1=rd(:,1);
            rd2=rd(:,2);
            
            app.a1s=[t a1];
            app.a2s=[t a2];
            app.rd1s=[t rd1];
            app.rd2s=[t rd2];
            app.rd21s=[t rd21];

            if(max(abs(a1))<1.0e-09 )
              a1=0.;
            end
            if(max(abs(a2))<1.0e-09 )
               a2=0.;
            end

            if(max(abs(rd1))<1.0e-09 )
                rd1=0.;
            end
            if(max(abs(rd2))<1.0e-09 )
                rd2=0.;
            end      
        
            s1=sprintf(' Max & Min Peaks \n\n Acceleration Response (G) \n');
            s3=sprintf('  Mass 1:  %7.4g   %7.4g ',max(a1),min(a1));
            s4=sprintf('  Mass 2:  %7.4g   %7.4g ',max(a2),min(a2));
        
            if(app.iu==1)
                s5=sprintf('Relative Displacement (inch) ');
            else
                s5=sprintf('Relative Displacement (mm) ');            
            end
            s7=sprintf('  Mass 1:  %7.4g   %7.4g ',max(rd1),min(rd1));
            s8=sprintf('  Mass 2:  %7.4g   %7.4g ',max(rd2),min(rd2));

            s9=sprintf('\n  Mass 2-Mass 1: \n       %7.4g   %7.4g ',max(rd21),min(rd21));
        
            xlabel3='Time (sec)';
            ylabel1='Accel (G)';
            ylabel2='Accel (G)';
            ylabel3='Accel (G)';
        
            t_string1='Base Input';
            t_string2='Mass 1 Response';
            t_string3='Mass 2 Response';
       
            [~]=...
                subplots_three_linlin_three_titles(fig_num,xlabel3,ylabel1,ylabel2,ylabel3,...
                                          data1,data2,data3,t_string1,t_string2,t_string3);

            ss=sprintf('%s\n %s\n %s\n\n %s\n\n %s\n %s\n %s\n\n %s ',s1,s3,s4,s5,s7,s8,s9);

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on'; 

        end

        % Button pushed function: SaveButton_Trans
        function SaveButton_TransPushed(app, event)
   
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end

            m=strtrim(app.ExportTrans.Value);
            n=strtrim(app.ExportAmplitude.Value);
            output_name=strtrim(app.OutputArrayNameEditFieldTrans.Value);

            if(strcmp(n,'mass 1 acceleration'))
                qdata=app.acc_trans;
                data=[qdata(:,1) qdata(:,2)];
            end
            if(strcmp(n,'mass 2 acceleration'))
                qdata=app.acc_trans;
                data=[qdata(:,1) qdata(:,3)];    
            end
            if(strcmp(n,'mass 1 - base  relative displacement'))
                qdata=app.rd_trans;
                data=[qdata(:,1) qdata(:,2)];    
            end
            if(strcmp(n,'mass 2 - base  relative displacement'))
                qdata=app.rd_trans;
                data=[qdata(:,1) qdata(:,3)];    
            end    
            if(contains(n,'mass 2 - mass 1'))
                data=app.rdm2m1_trans;    
            end

            try

                if(contains(m,'^2'))
                    data=fix_size(data);
                    sz=size(data);
        
                    for i=1:sz(1)
                        data(i,2)=data(i,2)^2;
                    end
                end

            catch
                warndlg('Save failed ^2');
                return;
            end

            try
                assignin('base', output_name, data); 
                msgbox('Data saved');
            catch
                warndlg('Save failed');
            end    

        end

        % Callback function
        function SaveButton_arbitraryPushed(app, event)
           
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)

            set_table(app);
        end

        % Button pushed function: GoToModalButton
        function GoToModalButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.ModalAnalysisTab;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            clear_modal(app);
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            clear_modal(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            clear_modal(app);
        end

        % Value changed function: SelectInertiaMethodListBox
        function SelectInertiaMethodListBoxValueChanged(app, event)

            set_table(app);
        end

        % Value changed function: ListBoxQ
        function ListBoxQValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ListBoxUniform
        function ListBoxUniformValueChanged(app, event)
            ChangeDamping(app);
        end

        % Button pushed function: SettoFirstModalFreqButton
        function SettoFirstModalFreqButtonPushed(app, event)
            app.FreqHzEditField.Value=sprintf('%8.4g',app.fn(1));
        end

        % Value changing function: AccelGEditField
        function AccelGEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FreqHzEditField
        function FreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: BaseAccelGEditField
        function BaseAccelGEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)
             try
                V=strtrim(app.OutputArrayListBox.Value);

                if(strcmp(V,'Acceleration Mass 1'))
                    data=app.a1_response_psd;
                end
                if(strcmp(V,'Acceleration Mass 2'))
                    data=app.a2_response_psd;            
                end
                if(strcmp(V,'Relative Displacement Mass 1'))
                    data=app.rd1_response_psd;                
                end
                if(strcmp(V,'Relative Displacement Mass 2'))
                    data=app.rd2_response_psd;                
                end
                if(strcmp(V,'Relative Displacement Mass 2 - Mass 1'))
                    data=app.rd21_response_psd;
                end
            
                output_name=app.OutputArrayNameEditField_PSD.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Button pushed function: UpdateListButton_arb
        function UpdateListButton_arbPushed(app, event)
            update_arb(app);
        end

        % Button pushed function: SaveButton_arbitrary
        function SaveButton_arbitraryPushed2(app, event)
            try
                V=strtrim(app.SelectOutputListBox.Value);    
                if(strcmp(V,'Acceleration Mass 1'))
                    data=app.a1s;
                end
                if(strcmp(V,'Acceleration Mass 2'))
                    data=app.a2s;            
                end
                if(strcmp(V,'Relative Disp Mass 1'))
                    data=app.rd1s;                
                end
                if(strcmp(V,'Relative Disp Mass 2'))
                    data=app.rd2s;                
                end
                if(strcmp(V,'Relative Disp Mass 2 - Mass 1'))
                    data=app.rd21s;
                end
            
                output_name=app.OutputArrayNameEditField.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end            
        end

        % Button pushed function: CalculateButton_mdof_srs_read
        function CalculateButton_mdof_srs_readPushed(app, event)
            clear(app);
            if(app.iflag==1)
                msgbox('Base excitation unavailable for semi-definite system');
                return;
            end
            check(app);

            try
                FS=app.SelectInputArrayListBox_srs.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            %%%%%

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            mdof_core(app); 

        end

        % Button pushed function: UpdateListButton_srs
        function UpdateListButton_srsPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_srs
        function listbox_method_srsValueChanged(app, event)
            array_method_srs(app);
        end

        % Value changed function: NumberofCoordinatesListBox
        function NumberofCoordinatesListBoxValueChanged(app, event)
            coordinates(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TwodofSystemUIFigure = uifigure('Visible', 'off');
            app.TwodofSystemUIFigure.Name = 'Two dof System';
            app.two_dof_system_ahver11byTomIrvineLabel = uilabel(app.TwodofSystemUIFigure);
            app.two_dof_system_ahver11byTomIrvineLabel.Text = 'two_dof_system_ah      ver 1.1      by Tom Irvine';
            app.TabGroup = uitabgroup(app.TwodofSystemUIFigure);
            app.SelectSystemTab = uitab(app.TabGroup);
            app.SelectSystemTab.Title = 'Select System';
            app.Image2_2 = uiimage(app.SelectSystemTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'semidefinite.jpg');
            app.Image2_3 = uiimage(app.SelectSystemTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'twodof_a.jpg');
            app.Image2_4 = uiimage(app.SelectSystemTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'two_dof_alt_fixed.jpg');
            app.Image2_5 = uiimage(app.SelectSystemTab);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'two_dof_system_c.jpg');
            app.SelectButton_1 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_1.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_1Pushed, true);
            app.SelectButton_1.Text = 'Select';
            app.SelectButton_2 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SelectButton_3 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.SelectButton_4 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.UnitsListBoxLabel = uilabel(app.SelectSystemTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SelectSystemTab);
            app.UnitsListBox.Items = {'English', 'Metric', ''};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.MassStiffnessTab = uitab(app.TabGroup);
            app.MassStiffnessTab.Title = 'Mass & Stiffness';
            app.Image_mk = uiimage(app.MassStiffnessTab);
            app.UITable = uitable(app.MassStiffnessTab);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.GoToModalButton = uibutton(app.MassStiffnessTab, 'push');
            app.GoToModalButton.ButtonPushedFcn = createCallbackFcn(app, @GoToModalButtonPushed, true);
            app.GoToModalButton.Text = 'Go To Modal';
            app.SelectInertiaMethodListBoxLabel = uilabel(app.MassStiffnessTab);
            app.SelectInertiaMethodListBoxLabel.Text = 'Select Inertia Method';
            app.SelectInertiaMethodListBox = uilistbox(app.MassStiffnessTab);
            app.SelectInertiaMethodListBox.Items = {'Direct Intertia', 'Radius of Gyration'};
            app.SelectInertiaMethodListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInertiaMethodListBoxValueChanged, true);
            app.SelectInertiaMethodListBox.Value = 'Direct Intertia';
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.TabGroup5 = uitabgroup(app.ModalAnalysisTab);
            app.CalculateTab_2 = uitab(app.TabGroup5);
            app.CalculateTab_2.Title = 'Calculate';
            app.Image_modal = uiimage(app.CalculateTab_2);
            app.CalculateButton = uibutton(app.CalculateTab_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassMatrixTextAreaLabel = uilabel(app.CalculateTab_2);
            app.MassMatrixTextAreaLabel.Text = 'Mass Matrix';
            app.MassMatrixTextArea = uitextarea(app.CalculateTab_2);
            app.StiffnessMatrixTextAreaLabel = uilabel(app.CalculateTab_2);
            app.StiffnessMatrixTextAreaLabel.Text = 'Stiffness Matrix';
            app.StiffnessMatrixTextArea = uitextarea(app.CalculateTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel = uilabel(app.CalculateTab_2);
            app.MassNormalizedEigenvectorsTextAreaLabel.Text = 'Mass Normalized Eigenvectors';
            app.MassNormalizedEigenvectorsTextArea = uitextarea(app.CalculateTab_2);
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.CalculateTab_2);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.NaturalFrequenciesTextArea = uitextarea(app.CalculateTab_2);
            app.SaveTab_2 = uitab(app.TabGroup5);
            app.SaveTab_2.Title = 'Save';
            app.OutputArrayNamesLabel = uilabel(app.SaveTab_2);
            app.OutputArrayNamesLabel.Text = 'Output Array Names';
            app.MassEditFieldLabel = uilabel(app.SaveTab_2);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.SaveTab_2, 'text');
            app.MassEditField.Value = ' two_dof_mass';
            app.StiffnessEditFieldLabel = uilabel(app.SaveTab_2);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.SaveTab_2, 'text');
            app.StiffnessEditField.Value = 'two_dof_stiffness';
            app.NaturalFrequencyEditFieldLabel = uilabel(app.SaveTab_2);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyEditField = uieditfield(app.SaveTab_2, 'text');
            app.NaturalFrequencyEditField.Value = 'two_dof_fn';
            app.ModeShapesEditFieldLabel = uilabel(app.SaveTab_2);
            app.ModeShapesEditFieldLabel.Text = 'Mode Shapes';
            app.ModeShapesEditField = uieditfield(app.SaveTab_2, 'text');
            app.ModeShapesEditField.Value = 'two_dof_ModeShapes';
            app.ParticipationFactorEditFieldLabel = uilabel(app.SaveTab_2);
            app.ParticipationFactorEditFieldLabel.Text = 'Participation Factor';
            app.ParticipationFactorEditField = uieditfield(app.SaveTab_2, 'text');
            app.ParticipationFactorEditField.Value = 'two_dof_PF';
            app.SaveButton = uibutton(app.SaveTab_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DampingTab_2 = uitab(app.TabGroup);
            app.DampingTab_2.Title = 'Damping';
            app.SelectTypesLabel = uilabel(app.DampingTab_2);
            app.SelectTypesLabel.Text = 'Select Types';
            app.DampingUnitLabel = uilabel(app.DampingTab_2);
            app.DampingUnitLabel.Text = 'DampingUnit';
            app.SaveDampingButton = uibutton(app.DampingTab_2, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.Label = uilabel(app.DampingTab_2);
            app.Label.Text = '';
            app.ListBoxUniform = uilistbox(app.DampingTab_2);
            app.ListBoxUniform.Items = {'Uniform', 'Varies by Mode'};
            app.ListBoxUniform.ValueChangedFcn = createCallbackFcn(app, @ListBoxUniformValueChanged, true);
            app.ListBoxUniform.Value = 'Uniform';
            app.Label_2 = uilabel(app.DampingTab_2);
            app.Label_2.Text = '';
            app.ListBoxQ = uilistbox(app.DampingTab_2);
            app.ListBoxQ.Items = {'Amplification Factor Q', 'Viscous Damping Coefficient'};
            app.ListBoxQ.ValueChangedFcn = createCallbackFcn(app, @ListBoxQValueChanged, true);
            app.ListBoxQ.Value = 'Amplification Factor Q';
            app.Mode1EditFieldLabel = uilabel(app.DampingTab_2);
            app.Mode1EditFieldLabel.Text = 'Mode 1';
            app.Mode1EditField = uieditfield(app.DampingTab_2, 'text');
            app.Mode2EditFieldLabel = uilabel(app.DampingTab_2);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.DampingTab_2, 'text');
            app.BaseExcitationTab_2 = uitab(app.TabGroup);
            app.BaseExcitationTab_2.Title = 'Base Excitation';
            app.TabGroup3 = uitabgroup(app.BaseExcitationTab_2);
            app.TransTab = uitab(app.TabGroup3);
            app.TransTab.Title = 'Trans';
            app.TabGroup4 = uitabgroup(app.TransTab);
            app.CalculateTab = uitab(app.TabGroup4);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton_Trans = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_TransPushed, true);
            app.CalculateButton_Trans.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.StartFreqHzEditField.Value = '10';
            app.EndFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.CalculateTab, 'text');
            app.EndFreqHzEditField.Value = '2000';
            app.SaveTab = uitab(app.TabGroup4);
            app.SaveTab.Title = 'Save';
            app.Label_3 = uilabel(app.SaveTab);
            app.Label_3.Text = '';
            app.ExportTrans = uilistbox(app.SaveTab);
            app.ExportTrans.Items = {'Transmissibility   -  freq (Hz) & (response/input)        ', 'Power Transmissibility   -  freq (Hz) & (response/input)^2'};
            app.ExportTrans.Value = 'Power Transmissibility   -  freq (Hz) & (response/input)^2';
            app.Label_4 = uilabel(app.SaveTab);
            app.Label_4.Text = '';
            app.ExportAmplitude = uilistbox(app.SaveTab);
            app.ExportAmplitude.Items = {'mass 1 acceleration                   ', 'mass 2 acceleration                   ', 'mass 1 - base  relative displacement  ', 'mass 2 - base  relative displacement  ', 'mass 2 - mass 1  relative displacement'};
            app.ExportAmplitude.Value = 'mass 1 acceleration                   ';
            app.AmplitudeTypeLabel = uilabel(app.SaveTab);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.ExportArrayLabel = uilabel(app.SaveTab);
            app.ExportArrayLabel.Text = 'Export Array';
            app.OutputArrayNameEditField_2Label = uilabel(app.SaveTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditFieldTrans = uieditfield(app.SaveTab, 'text');
            app.SaveButton_Trans = uibutton(app.SaveTab, 'push');
            app.SaveButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_TransPushed, true);
            app.SaveButton_Trans.Text = 'Save';
            app.SineTab = uitab(app.TabGroup3);
            app.SineTab.Title = 'Sine';
            app.CalculateButton_sine = uibutton(app.SineTab, 'push');
            app.CalculateButton_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sinePushed, true);
            app.CalculateButton_sine.Text = 'Calculate';
            app.SteadyStateSineBaseInputLabel = uilabel(app.SineTab);
            app.SteadyStateSineBaseInputLabel.Text = 'Steady-State Sine Base Input';
            app.SettoFirstModalFreqButton = uibutton(app.SineTab, 'push');
            app.SettoFirstModalFreqButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstModalFreqButtonPushed, true);
            app.SettoFirstModalFreqButton.Text = 'Set to First Modal Freq';
            app.AccelGEditFieldLabel = uilabel(app.SineTab);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.SineTab, 'text');
            app.AccelGEditField.ValueChangingFcn = createCallbackFcn(app, @AccelGEditFieldValueChanging, true);
            app.FreqHzEditFieldLabel = uilabel(app.SineTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SineTab, 'text');
            app.FreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanging, true);
            app.HalfSineTab = uitab(app.TabGroup3);
            app.HalfSineTab.Title = 'Half-Sine';
            app.CalculateButton_halfsine = uibutton(app.HalfSineTab, 'push');
            app.CalculateButton_halfsine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_halfsinePushed, true);
            app.CalculateButton_halfsine.Text = 'Calculate';
            app.ResponsetoHalfSineBaseInputLabel = uilabel(app.HalfSineTab);
            app.ResponsetoHalfSineBaseInputLabel.Text = 'Response to Half-Sine Base Input';
            app.BaseAccelGEditFieldLabel = uilabel(app.HalfSineTab);
            app.BaseAccelGEditFieldLabel.Text = 'Base Accel (G)';
            app.BaseAccelGEditField = uieditfield(app.HalfSineTab, 'text');
            app.BaseAccelGEditField.ValueChangingFcn = createCallbackFcn(app, @BaseAccelGEditFieldValueChanging, true);
            app.DurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.HalfSineTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.PSDTab = uitab(app.TabGroup3);
            app.PSDTab.Title = 'PSD';
            app.TabGroup7 = uitabgroup(app.PSDTab);
            app.CalculateTab_4 = uitab(app.TabGroup7);
            app.CalculateTab_4.Title = 'Calculate';
            app.CalculateButton_psd = uibutton(app.CalculateTab_4, 'push');
            app.CalculateButton_psd.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_psdPushed, true);
            app.CalculateButton_psd.Text = 'Calculate';
            app.InputArrayNamePSD = uilabel(app.CalculateTab_4);
            app.InputArrayNamePSD.Text = {'The input array must have two columns:  Freq(Hz) & Accel PSD(G^2/Hz)'; ''};
            app.listbox_method = uilistbox(app.CalculateTab_4);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab_4);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.CalculateTab_4, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculateTab_4);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculateTab_4);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.SaveTab_4 = uitab(app.TabGroup7);
            app.SaveTab_4.Title = 'Save';
            app.SaveButton_PSD = uibutton(app.SaveTab_4, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.SaveTab_4);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.SaveTab_4, 'text');
            app.OutputArrayListBoxLabel = uilabel(app.SaveTab_4);
            app.OutputArrayListBoxLabel.Text = 'Output Array';
            app.OutputArrayListBox = uilistbox(app.SaveTab_4);
            app.OutputArrayListBox.Items = {'Acceleration Mass 1                  ', 'Acceleration Mass 2                  ', 'Relative Displacement Mass 1         ', 'Relative Displacement Mass 2         ', 'Relative Displacement Mass 2 - Mass 1', ''};
            app.OutputArrayListBox.Value = 'Acceleration Mass 1                  ';
            app.ArbitraryTab = uitab(app.TabGroup3);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.TabGroup6 = uitabgroup(app.ArbitraryTab);
            app.CalculateTab_3 = uitab(app.TabGroup6);
            app.CalculateTab_3.Title = 'Calculate';
            app.CalculateButton_arbitrary = uibutton(app.CalculateTab_3, 'push');
            app.CalculateButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_arbitraryPushed, true);
            app.CalculateButton_arbitrary.Text = 'Calculate';
            app.ResponsetoarbitrarybaseinputLabel = uilabel(app.CalculateTab_3);
            app.ResponsetoarbitrarybaseinputLabel.Text = {'Response to arbitrary base input.  '; ''; 'The input array must have two columns:    Time(sec) & Accel (G)'};
            app.listbox_method_arb = uilistbox(app.CalculateTab_3);
            app.listbox_method_arb.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_arb.Tag = 'listbox_method';
            app.listbox_method_arb.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.CalculateTab_3);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.UpdateListButton_arb = uibutton(app.CalculateTab_3, 'push');
            app.UpdateListButton_arb.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_arbPushed, true);
            app.UpdateListButton_arb.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.CalculateTab_3);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_arb = uilistbox(app.CalculateTab_3);
            app.SelectInputArrayListBox_arb.Items = {};
            app.SelectInputArrayListBox_arb.Value = {};
            app.SaveTab_3 = uitab(app.TabGroup6);
            app.SaveTab_3.Title = 'Save';
            app.SaveButton_arbitrary = uibutton(app.SaveTab_3, 'push');
            app.SaveButton_arbitrary.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_arbitraryPushed2, true);
            app.SaveButton_arbitrary.Text = 'Save';
            app.SelectOutputListBoxLabel = uilabel(app.SaveTab_3);
            app.SelectOutputListBoxLabel.Text = 'Select Output';
            app.SelectOutputListBox = uilistbox(app.SaveTab_3);
            app.SelectOutputListBox.Items = {'Acceleration Mass 1', 'Acceleration Mass 2', 'Relative Disp Mass 1', 'Relative Disp Mass 2', 'Relative Disp Mass 2 - Mass 1'};
            app.SelectOutputListBox.Value = 'Acceleration Mass 1';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveTab_3);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveTab_3, 'text');
            app.MDOFSRSTab = uitab(app.TabGroup3);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.MDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = 'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)';
            app.TabGroup8 = uitabgroup(app.MDOFSRSTab);
            app.ReadSRSTab = uitab(app.TabGroup8);
            app.ReadSRSTab.Title = 'Read SRS';
            app.CalculateButton_mdof_srs_read = uibutton(app.ReadSRSTab, 'push');
            app.CalculateButton_mdof_srs_read.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_mdof_srs_readPushed, true);
            app.CalculateButton_mdof_srs_read.Text = 'Calculate';
            app.listbox_method_srs = uilistbox(app.ReadSRSTab);
            app.listbox_method_srs.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_srs.ValueChangedFcn = createCallbackFcn(app, @listbox_method_srsValueChanged, true);
            app.listbox_method_srs.Tag = 'listbox_method';
            app.listbox_method_srs.Value = 'Array Preloaded in Matlab';
            app.text1_3 = uilabel(app.ReadSRSTab);
            app.text1_3.Tag = 'text1';
            app.text1_3.Text = 'Select Data Input Method';
            app.UpdateListButton_srs = uibutton(app.ReadSRSTab, 'push');
            app.UpdateListButton_srs.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srsPushed, true);
            app.UpdateListButton_srs.Text = 'Update List';
            app.SelectInputArrayListBox_srs = uilistbox(app.ReadSRSTab);
            app.SelectInputArrayListBox_srs.Items = {};
            app.SelectInputArrayListBox_srs.Value = {};
            app.ManuallyEnterSRSTab = uitab(app.TabGroup8);
            app.ManuallyEnterSRSTab.Title = 'Manually Enter SRS';
            app.CalculateButton_mdof_srs = uibutton(app.ManuallyEnterSRSTab, 'push');
            app.CalculateButton_mdof_srs.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_mdof_srsPushed, true);
            app.CalculateButton_mdof_srs.Text = 'Calculate';
            app.NumberofCoordinatesLabel = uilabel(app.ManuallyEnterSRSTab);
            app.NumberofCoordinatesLabel.Text = {'Number of '; 'Coordinates'};
            app.NumberofCoordinatesListBox = uilistbox(app.ManuallyEnterSRSTab);
            app.NumberofCoordinatesListBox.Items = {'2', '3', '4', '5', '6'};
            app.NumberofCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCoordinatesListBoxValueChanged, true);
            app.NumberofCoordinatesListBox.Value = '2';
            app.UITable_srs = uitable(app.ManuallyEnterSRSTab);
            app.UITable_srs.ColumnName = {'fn (Hz)'; 'Accel (G)'};
            app.UITable_srs.RowName = {};
            app.UITable_srs.ColumnEditable = [true true];
            app.SpecificationQEditFieldLabel = uilabel(app.MDOFSRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.MDOFSRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.ResultsTextAreaLabel = uilabel(app.BaseExcitationTab_2);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.BaseExcitationTab_2);
            app.Image_base = uiimage(app.BaseExcitationTab_2);
            app.ReturnButton = uibutton(app.TwodofSystemUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.TwodofSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_system_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TwodofSystemUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TwodofSystemUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwodofSystemUIFigure)
        end
    end
end