classdef two_dof_system_dashpots_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        twodofdashpotsUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SelectSystemTab                matlab.ui.container.Tab
        SelectButton_2                 matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        UnitsListBox                   matlab.ui.control.ListBox
        UnitsListBoxLabel              matlab.ui.control.Label
        ModalAnalysisTab               matlab.ui.container.Tab
        CalculateButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        EnterValuesLabel               matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        ViewModalResultsTab            matlab.ui.container.Tab
        MatricesTextArea               matlab.ui.control.TextArea
        MatricesTextAreaLabel          matlab.ui.control.Label
        EigenvalueVectorResultsTextArea  matlab.ui.control.TextArea
        EigenvalueVectorResultsTextAreaLabel  matlab.ui.control.Label
        ExportModalTab                 matlab.ui.container.Tab
        OutputArrayNameEditField       matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel  matlab.ui.control.Label
        OutputListBox                  matlab.ui.control.ListBox
        OutputListBoxLabel             matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        TransferFunctionTab            matlab.ui.container.Tab
        FRFOutputArraysHdreceptanceHvmobilityHaacceleranceLabel  matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.EditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.EditField
        MinEditFieldLabel              matlab.ui.control.Label
        CalculateFRFButton             matlab.ui.control.Button
        ReturnButton                   matlab.ui.control.Button
        two_dof_system_dashpots_appver13byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ModeShapes
        fn
        A
        B
        iu
        model
        M
        C
        K
        lambda
        ar
        br
        Eigenvalues
    end
    
    methods (Access = private)

       function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        
        function ChangeUnits(app)

            ClosePlots(app);

            app.EigenvalueVectorResultsTextArea.Value='';

            Items = strtrim(app.UnitsListBox.Items);
            im = strtrim(app.UnitsListBox.Value);
            app.iu = find(strcmp(Items, im));


            if(app.iu==1)
                mu='lbm';
                cu='lbf sec/in';
                ku='lbf/in';
            else
                mu='kg';
                cu='N sec/m';
                ku='N/m';            
            end

            if(app.model==1)
                app.Image_3.ImageSource='two_dof_force_ma.jpg';
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='c1';
                ss{4,1}='c2';
                ss{5,1}='k1';
                ss{6,1}='k2';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';  
                ss{4,2}='';
                ss{5,2}='';
                ss{6,2}='';                
                ss{1,3}=mu;
                ss{2,3}=mu;
                ss{3,3}=cu;
                ss{4,3}=cu;          
                ss{5,3}=ku;
                ss{6,3}=ku;          
            else
                app.Image_3.ImageSource='two_dof_force_mb.jpg';
                ss{1,1}='m1';
                ss{2,1}='m2';
                ss{3,1}='c1';
                ss{4,1}='c2';
                ss{5,1}='c3';
                ss{6,1}='k1';
                ss{7,1}='k2';
                ss{8,1}='k3';
                ss{1,2}='';
                ss{2,2}='';
                ss{3,2}='';  
                ss{4,2}='';
                ss{5,2}='';
                ss{6,2}='';  
                ss{7,2}='';
                ss{8,2}='';              
                ss{1,3}=mu;
                ss{2,3}=mu;
                ss{3,3}=cu;
                ss{4,3}=cu;  
                ss{5,3}=cu; 
                ss{6,3}=ku;
                ss{7,3}=ku;
                ss{8,3}=ku;  
            end

            app.UITable.Data=ss; 

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.model=1;

            ChangeUnits(app);

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Items = strtrim(app.OutputListBox.Items);
            im = strtrim(app.OutputListBox.Value);
            n = find(strcmp(Items, im));
            
            if(n==1)
                data=app.M;
            end
            if(n==2)
                data=app.C;
            end
            if(n==3)
                data=app.K;
            end
            if(n==4)
                data=app.A;
            end
            if(n==5)
                data=app.fn;
            end
            if(n==6)
                data=app.ModeShapes;  
            end
            
            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name,data);
            
            msgbox('Save Complete');
        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            
            ChangeUnits(app);
        end

        % Callback function
        function ModelListBoxValueChanged(app, event)
        
            ChangeUnits(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            Y=char(app.UITable.Data);
            sz=size(Y);
            N=round(sz(1)/3);

            if(app.model==1)
                m1=str2num(Y(N+1,:));
                m2=str2num(Y(N+2,:));
                c1=str2num(Y(N+3,:));
                c2=str2num(Y(N+4,:)); 
                k1=str2num(Y(N+5,:));
                k2=str2num(Y(N+6,:)); 
                
                app.K=[(k1+k2) -k2; -k2 k2];
                
                app.C=[(c1+c2) -c2; -c2 c2];

            else
                m1=str2num(Y(N+1,:));
                m2=str2num(Y(N+2,:));
                c1=str2num(Y(N+3,:));
                c2=str2num(Y(N+4,:));
                c3=str2num(Y(N+5,:)); 
                k1=str2num(Y(N+6,:));
                k2=str2num(Y(N+7,:)); 
                k3=str2num(Y(N+8,:));   

                app.K=[(k1+k2) -k2; -k2 (k2+k3)];
                
                app.C=[(c1+c2) -c2; -c2 (c2+c3)];


            end

            app.M=zeros(2,2);

            app.M(1,1)=m1;
            app.M(2,2)=m2;

            if(app.iu==1)
               app.M=app.M/386.;
            end

            Z=zeros(2,2);

            % app.A=[ app.C app.M ; app.M  Z ];
            % app.B=[ app.K Z ; Z -app.M ];     
            % app.A=eye(4,4);
            % [app.ModeShapes,Evals]=eig(app.B,app.A);

            app.A=[Z eye(2,2); -pinv(app.M)*app.K  -pinv(app.M)*app.C];

            [V,D] = eig(app.A);

            Evals=diag(D);
            app.ModeShapes=V;
            
            szz=max(size(Evals));
            
            EEE=zeros(szz,szz+2);
            
            for i=1:szz
                EEE(i,1)=abs(Evals(i));
                EEE(i,2)=Evals(i);    
            end
            EEE(:,3:szz+2)=transpose(app.ModeShapes);
            EEE=sortrows(EEE);
            
            app.Eigenvalues=EEE(:,2);
            app.lambda=app.Eigenvalues;
            app.ModeShapes=transpose(EEE(:,3:szz+2));

            tpi=2*pi;
                        
            disp(' ');
            disp(' Eigenvalues');
            disp(' ');
            
            ss=sprintf(' Eigenvalues \n\n');
            for i=1:4
                v=app.Eigenvalues(i);
                out1=sprintf('Complex: %8.4g + %8.4gi   Mag: %8.4g rad/sec  f=%8.4g Hz',real(v),imag(v),abs(v),abs(v)/tpi);
                disp(out1);
                ss=sprintf('%s %s\n',ss,out1);
                app.fn(i)=v;
            end
            
            disp(' ');
            disp(' Mode Shapes');

            ss=sprintf('%s \n Eigenvectors 1 & 2 \n\n',ss);

            for i=1:4
                for j=1:2
                    if(imag(app.ModeShapes(i,j))>=0)
                        ss=sprintf('%s \t  %6.2e +%6.2ei ',ss,real(app.ModeShapes(i,j)),imag(app.ModeShapes(i,j)));
                    else
                        ss=sprintf('%s \t  %6.2e %6.2ei ',ss,real(app.ModeShapes(i,j)),imag(app.ModeShapes(i,j)));                        
                    end
                end    
                ss=sprintf('%s \n',ss);
            end    

            ss=sprintf('%s \n Eigenvectors 3 & 4 \n\n',ss);

            for i=1:4
                for j=3:4
                    if(imag(app.ModeShapes(i,j))>=0)
                        ss=sprintf('%s \t  %6.2e +%6.2ei ',ss,real(app.ModeShapes(i,j)),imag(app.ModeShapes(i,j)));
                    else
                        ss=sprintf('%s \t  %6.2e %6.2ei ',ss,real(app.ModeShapes(i,j)),imag(app.ModeShapes(i,j)));                        
                    end
                end    
                ss=sprintf('%s \n',ss);
            end    

      
            app.EigenvalueVectorResultsTextArea.Value=ss;           

            ss=sprintf(' Mass Matrix \n\n');
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.M(1,1),app.M(1,2));
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.M(2,1),app.M(2,2));    

            ss=sprintf('%s \n Damping Coefficient Matrix \n\n',ss);
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.C(1,1),app.C(1,2));
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.C(2,1),app.C(2,2));

            ss=sprintf('%s \n Stiffness Matrix \n\n',ss);
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.K(1,1),app.K(1,2));
            ss=sprintf('%s  %7.3e  \t %7.3e \n ',ss,app.K(2,1),app.K(2,2));

            app.MatricesTextArea.Value=ss;

            app.TabGroup.SelectedTab=app.ViewModalResultsTab;

        end

        % Button pushed function: CalculateFRFButton
        function CalculateFRFButtonPushed(app, event)
            ClosePlots(app);

            if(isempty(app.Eigenvalues))
                warndlg('Perform Modal Analysis first');
                return;
            end

            fmin=str2num(app.MinEditField.Value);
            fmax=str2num(app.MaxEditField.Value);

            if(fmin<=0.01)
                fmin=0.01;
            end    
            
            sz=size(app.ModeShapes);
            
            two_N=sz(1);
            N=round(two_N/2);
            
            %
            [freq,omega,np]=frequencies_octave_spaced(fmin,fmax,96);

            disp(' ');
            disp(' Output frf arrays:');
            disp(' ');
            disp('   Hd=receptance  Hv=mobility  Ha=accelerance ');
            disp(' ');

            MI=pinv(app.ModeShapes);

            app.lambda
            app.lambda=diag(MI*app.A*app.ModeShapes);
        
            eta=zeros(two_N,np); 

            H=zeros(np,4);
            Hv=zeros(np,4);
            Ha=zeros(np,4);

            for i=1:1
                
                for k=1:2 
                   
                    if(k==1)
                        fq=pinv(app.M)*[1 0]';
                    else
                        fq=pinv(app.M)*[0 1]';                    
                    end    

                    ff=[0 0 fq(1) fq(2)];
                    num=MI*ff';
                    
                    for j=1:np
                        
                        jomega=omega(j)*1i;
            
                        for r=1:two_N
                                
                            den= jomega-app.lambda(r);
                            eta(r,j)=num(r)/den;
       
                        end
                        
                    end   

                    Y=app.ModeShapes*eta;

                    H=Y';

                    for iv=1:np
                        jomega=omega(iv)*1i;
                        Hv(iv,:)=H(iv,:)*jomega;
                        Ha(iv,:)=Hv(iv,:)*jomega;
                    end    
            
                    for ijk=1:2

                        output_name_d=sprintf('Hd_%d%d',ijk,k);
                        assignin('base', output_name_d, [freq H(:,ijk)]);
                        
                        output_name_v=sprintf('Hv_%d%d',ijk,k);
                        assignin('base', output_name_v, [freq Hv(:,ijk)]); 
                       
                        output_name_a=sprintf('Ha_%d%d',ijk,k);
                        assignin('base', output_name_a, [freq Ha(:,ijk)]);    
                         
                        fprintf(' %s  %s  %s \n',output_name_d,output_name_v,output_name_a);
                        
                        eval(sprintf('Hd_%d%d=[freq H(:,ijk)];',ijk,k));
                        eval(sprintf('Hv_%d%d=[freq Hv(:,ijk)];',ijk,k));
                        eval(sprintf('Ha_%d%d=[freq Ha(:,ijk)];',ijk,k));
                            
                        eval(sprintf('Hd_%d%d_abs=[freq abs(H(:,ijk))];',ijk,k));
                        eval(sprintf('Hv_%d%d_abs=[freq abs(Hv(:,ijk))];',ijk,k));
                        eval(sprintf('Ha_%d%d_abs=[freq abs(Ha(:,ijk))];',ijk,k));            
                        
                    end
                end
                
            end
            
            md=5;
            fig_num=1;
            
            if(N==2)
                [fig_num]=state_space_plots_two(fig_num,md,app.iu,Hd_11_abs,Hd_12_abs,Hd_22_abs,...
                                                              Hv_11_abs,Hv_12_abs,Hv_22_abs,...
                                                              Ha_11_abs,Ha_12_abs,Ha_22_abs);                                              
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
  
            app.EigenvalueVectorResultsTextArea.Value='';
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
   
            app.EigenvalueVectorResultsTextArea.Value='';
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            app.EigenvalueVectorResultsTextArea.Value='';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.model=1;

            ChangeUnits(app);

            app.TabGroup.SelectedTab=app.ModalAnalysisTab; 
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            app.model=2;

            ChangeUnits(app);

            app.TabGroup.SelectedTab=app.ModalAnalysisTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.twodofdashpotsUIFigure = uifigure('Visible', 'off');
            app.twodofdashpotsUIFigure.Name = 'two dof dashpots';
            app.two_dof_system_dashpots_appver13byTomIrvineLabel = uilabel(app.twodofdashpotsUIFigure);
            app.two_dof_system_dashpots_appver13byTomIrvineLabel.Text = 'two_dof_system_dashpots_app    ver 1.3   by Tom Irvine';
            app.ReturnButton = uibutton(app.twodofdashpotsUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TabGroup = uitabgroup(app.twodofdashpotsUIFigure);
            app.SelectSystemTab = uitab(app.TabGroup);
            app.SelectSystemTab.Title = 'Select System';
            app.UnitsListBoxLabel = uilabel(app.SelectSystemTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.SelectSystemTab);
            app.UnitsListBox.Items = {'English', 'metric'};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English';
            app.Image = uiimage(app.SelectSystemTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'two_dof_force_ma.jpg');
            app.Image_2 = uiimage(app.SelectSystemTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'two_dof_force_mb.jpg');
            app.SelectButton = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.SelectSystemTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.ModalAnalysisTab = uitab(app.TabGroup);
            app.ModalAnalysisTab.Title = 'Modal Analysis';
            app.Image_3 = uiimage(app.ModalAnalysisTab);
            app.EnterValuesLabel = uilabel(app.ModalAnalysisTab);
            app.EnterValuesLabel.Text = 'Enter Values';
            app.UITable = uitable(app.ModalAnalysisTab);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.CalculateButton = uibutton(app.ModalAnalysisTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ViewModalResultsTab = uitab(app.TabGroup);
            app.ViewModalResultsTab.Title = 'View Modal Results';
            app.EigenvalueVectorResultsTextAreaLabel = uilabel(app.ViewModalResultsTab);
            app.EigenvalueVectorResultsTextAreaLabel.Text = 'Eigenvalue & Vector Results';
            app.EigenvalueVectorResultsTextArea = uitextarea(app.ViewModalResultsTab);
            app.EigenvalueVectorResultsTextArea.Editable = 'off';
            app.MatricesTextAreaLabel = uilabel(app.ViewModalResultsTab);
            app.MatricesTextAreaLabel.Text = 'Matrices';
            app.MatricesTextArea = uitextarea(app.ViewModalResultsTab);
            app.ExportModalTab = uitab(app.TabGroup);
            app.ExportModalTab.Title = 'Export Modal';
            app.SaveButton = uibutton(app.ExportModalTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputListBoxLabel = uilabel(app.ExportModalTab);
            app.OutputListBoxLabel.Text = 'Output';
            app.OutputListBox = uilistbox(app.ExportModalTab);
            app.OutputListBox.Items = {'Mass Matrix', 'Damping Coefficient Matrix', 'Stiffness Matrix', 'A Matrix', 'Natural Frequencies', 'Normal Modes'};
            app.OutputListBox.Value = 'Mass Matrix';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportModalTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportModalTab, 'text');
            app.TransferFunctionTab = uitab(app.TabGroup);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.CalculateFRFButton = uibutton(app.TransferFunctionTab, 'push');
            app.CalculateFRFButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFRFButtonPushed, true);
            app.CalculateFRFButton.Text = 'Calculate FRF';
            app.MinEditFieldLabel = uilabel(app.TransferFunctionTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.TransferFunctionTab, 'text');
            app.MaxEditFieldLabel = uilabel(app.TransferFunctionTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.TransferFunctionTab, 'text');
            app.PlotFrequenciesHzLabel = uilabel(app.TransferFunctionTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.FRFOutputArraysHdreceptanceHvmobilityHaacceleranceLabel = uilabel(app.TransferFunctionTab);
            app.FRFOutputArraysHdreceptanceHvmobilityHaacceleranceLabel.Text = {'FRF Output Arrays: '; ' '; ' Hd_ij = receptance  '; ' Hv_ij = mobility  '; ' Ha_ij = accelerance  '; ''; ' where i & j are dof numbers'};
            % Show the figure after all components are created
            app.twodofdashpotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_system_dashpots_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.twodofdashpotsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.twodofdashpotsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.twodofdashpotsUIFigure)
        end
    end
end