classdef two_dof_system_force_bb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UITwodofForcebbUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NaturalFreqTab                  matlab.ui.container.Tab
        Mass2EditFieldLabel             matlab.ui.control.Label
        Mass2EditField                  matlab.ui.control.NumericEditField
        Mass1EditFieldLabel             matlab.ui.control.Label
        Mass1EditField                  matlab.ui.control.NumericEditField
        Stiffness2EditFieldLabel        matlab.ui.control.Label
        Stiffness2EditField             matlab.ui.control.NumericEditField
        Stiffness1EditFieldLabel        matlab.ui.control.Label
        Stiffness1EditField             matlab.ui.control.NumericEditField
        UnitsListBoxLabel               matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        CalculateButton                 matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        SelectTypesLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ListBoxQ                        matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        ListBoxUniform                  matlab.ui.control.ListBox
        Mode1EditFieldLabel             matlab.ui.control.Label
        Mode1EditField                  matlab.ui.control.NumericEditField
        DampingUnitLabel                matlab.ui.control.Label
        Mode2EditFieldLabel             matlab.ui.control.Label
        Mode2EditField                  matlab.ui.control.NumericEditField
        SaveDampingButton               matlab.ui.control.Button
        TransFRFTab                     matlab.ui.container.Tab
        CalculateButton_Trans           matlab.ui.control.Button
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.NumericEditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        EndFreqHzEditField              matlab.ui.control.NumericEditField
        SaveButton_Trans                matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        ExportTrans                     matlab.ui.control.ListBox
        ExportFormatLabel               matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ExportAmplitude                 matlab.ui.control.ListBox
        AmplitudeTypeLabel              matlab.ui.control.Label
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        OutputArrayNameEditFieldTrans   matlab.ui.control.EditField
        ForcedofListBoxLabel            matlab.ui.control.Label
        ForcedofListBox                 matlab.ui.control.ListBox
        ResponsedofListBoxLabel         matlab.ui.control.Label
        ResponsedofListBox              matlab.ui.control.ListBox
        SteadySineTab                   matlab.ui.container.Tab
        ForcelbfEditFieldLabel          matlab.ui.control.Label
        ForcelbfEditField               matlab.ui.control.NumericEditField
        SteadyStateAppliedForceLabel    matlab.ui.control.Label
        FreqHzEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        CalculateButton_Sine            matlab.ui.control.Button
        SettoFirstModalFreqButton       matlab.ui.control.Button
        AppliedForcedofListBoxLabel     matlab.ui.control.Label
        AppliedForcedofListBox          matlab.ui.control.ListBox
        HalfSineTab                     matlab.ui.container.Tab
        CalculateButton_HalfSine        matlab.ui.control.Button
        AppliedForcelbfLabel            matlab.ui.control.Label
        AppliedHSForceEditField         matlab.ui.control.NumericEditField
        ResponsetoHalfSineAppliedForceLabel  matlab.ui.control.Label
        PulseDurationsecEditFieldLabel  matlab.ui.control.Label
        PulseDurationsecEditField       matlab.ui.control.NumericEditField
        AppliedForcedofListBox_2Label   matlab.ui.control.Label
        AppliedForcedofListBox_HS       matlab.ui.control.ListBox
        SaveHSButton                    matlab.ui.control.Button
        OutputTimeHistoryArrayListBoxLabel  matlab.ui.control.Label
        OutputTimeHistoryArrayListBox   matlab.ui.control.ListBox
        OutputArrayNameEditField_3Label  matlab.ui.control.Label
        OutputArrayNameEditField_HS     matlab.ui.control.EditField
        TotalDurationsecEditFieldLabel  matlab.ui.control.Label
        TotalDurationsecEditField       matlab.ui.control.NumericEditField
        ArbitraryTab                    matlab.ui.container.Tab
        CalculateButton_Arbitrary       matlab.ui.control.Button
        InputArbitraryForceArray        matlab.ui.control.Label
        ResponsetoarbitraryappliedforceLabel  matlab.ui.control.Label
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        SaveArbitraryButton             matlab.ui.control.Button
        OutputTimeHistoryArrayListBox_2Label  matlab.ui.control.Label
        OutputTimeHistoryArrayListBox_Arbitrary  matlab.ui.control.ListBox
        AppliedForcedofListBox_2Label_2  matlab.ui.control.Label
        AppliedForcedofListBox_Arbitrary  matlab.ui.control.ListBox
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        PSDTab                          matlab.ui.container.Tab
        CalculateButton_PSD             matlab.ui.control.Button
        InputArrayNamePSD               matlab.ui.control.Label
        InputArrayNameEditField_3Label  matlab.ui.control.Label
        InputArrayNameEditField_PSD     matlab.ui.control.EditField
        SaveButton_PSD                  matlab.ui.control.Button
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        OutputArrayNameEditField_PSD    matlab.ui.control.EditField
        OutputArrayListBoxLabel         matlab.ui.control.Label
        OutputArrayListBox              matlab.ui.control.ListBox
        AppliedForcedofListBox_3Label   matlab.ui.control.Label
        AppliedForcedofListBox_PSD      matlab.ui.control.ListBox
        DiracDeltaTab                   matlab.ui.container.Tab
        CalculateButton_Dirac           matlab.ui.control.Button
        ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel  matlab.ui.control.Label
        TotalDurationsecEditField_DiracLabel  matlab.ui.control.Label
        TotalDurationsecEditField_Dirac  matlab.ui.control.NumericEditField
        SampleRateHzEditField_Label_Dirac  matlab.ui.control.Label
        SampleRateHzEditField_Dirac     matlab.ui.control.NumericEditField
        TotalImpulselbfsecEditField_Label_Dirac  matlab.ui.control.Label
        TotalImpulselbfsecEditField_Dirac  matlab.ui.control.NumericEditField
        SaveDiracButton                 matlab.ui.control.Button
        OutputTimeHistoryArrayListBox_Label_Dirace  matlab.ui.control.Label
        OutputTimeHistoryArrayListBox_Dirac  matlab.ui.control.ListBox
        OutputArrayNameEditField_Label_Dirace  matlab.ui.control.Label
        OutputArrayNameEditField_Dirac  matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        two_dof_base_aaver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        ModalToolBoxButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        iu    % unit
        mass  % mass array
        stiffness % stiffness array
        fn    % natural frequency vector
        omegan % angular natural frequency vector
        ModeShapes % mass-normalized mode shapes
        MST   % mode shapes transpose
        pff   % participation factors
        damp1 % viscous damping ratio 1
        damp2 % viscous damping ratio 2  
        dm    % damping vector
        mm    % mass vector
        a_f_mag          % acceleration magnitude
        a_f_mag_phase    % acceleration magnitude & phase
        a_f_mag_complex  % acceleration complex
        v_f_mag          % velocity magnitude
        v_f_mag_phase    % velocity magnitude & phase
        v_f_mag_complex  % velocity complex
        d_f_mag          % displacement magnitude
        d_f_mag_phase    % displacement magnitude & phase
        d_f_mag_complex  % displacement complex
        zflag  % flag
        force_unit   % force unit string
        displacement % displacement time history
        rel_disp     % relative displacement time history
        velocity     % velocity time history
        acceleration % acceleration time history
        FD1 % force & displacement 1
        FD2 % force & displacement 2

    end
    
    methods (Access = private)
        
        function ChangeDamping(app)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                app.DampingUnitLabel.Text = 'Q';
            else
                app.DampingUnitLabel.Text = 'Ratio';
            end
            
            if(contains(m,'Uniform'))
                app.Mode1EditFieldLabel.Text='Uniform';
                app.Mode2EditField.Visible = 'off';
                app.Mode2EditFieldLabel.Visible = 'off';                
            else
                app.Mode1EditFieldLabel.Text='Mode 1';                
                app.Mode2EditField.Visible = 'on';
                app.Mode2EditFieldLabel.Visible = 'on';                 
            end            

        end
        
        function check(app)
        
            app.SaveArbitraryButton.Enable='off';     
            app.SaveButton_Trans.Enable='off';
            app.SaveButton_PSD.Enable='off';
            app.SaveHSButton.Enable='off';
            app.SaveDiracButton.Enable='off';
        
            app.zflag=0;
        
            if(isempty(app.fn))
                app.zflag=1;
                warndlg('Run Natural Frequency Tab')
                return;                
            end
            if(isempty(app.damp1)||isempty(app.damp2))
                app.zflag=1;
                warndlg('Run Damping Tab')
                return;                
            end            
        
            try
                if(min(app.fn)<=0)
                    app.zflag=1;
                    warndlg('Zero frequency detected. Run Natural Frequency Tab')
                    return;
                end
            catch
                app.zflag=1;
                warndlg('Run Natural Frequency Tab')
                return;
            end
            
            try
                if(app.damp1>=0 && app.damp2>=0)
                else
                    app.zflag=1;
                    warndlg('Enter Damping at Damping Tab');
                    return;                
                end
            catch
                app.zflag=1;
                warndlg('Enter Damping at Damping Tab');
                return;
            end
            
        end
        
        function close_figures(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                    
        end
        
        function ChangeUnits(app)
            n=app.UnitsListBox.Value;
            m=strtrim(app.AppliedForcedofListBox_Arbitrary.Value);
            
            if(contains(n,'English'))
                app.TotalImpulselbfsecEditField_Label_Dirac.Text='Total Impulse (lbf sec)';
                app.ForcelbfEditFieldLabel.Text='Force (lbf)';
                app.force_unit='lbf';
                app.AppliedForcelbfLabel.Text='Applied Force (lbf)';
                if(strcmp(m,'1'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 1 (lbf)');
                end
                if(strcmp(m,'2'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 2 (lbf)');                  
                end
                if(strcmp(m,'1 & 2'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 1 & Force 2 (lbf)');                 
                end 
                app.InputArbitraryForceArray.Text=ss; 
                app.InputArrayNamePSD.Text='The PSD input array must have two columns:  Freq(Hz) & Force(lbf^2/Hz)';
            else
                app.TotalImpulselbfsecEditField_Label_Dirac.Text='Total Impulse (N sec)';   
                app.ForcelbfEditFieldLabel.Text='Force (N)';  
                app.force_unit='N';
                app.AppliedForcelbfLabel.Text='Applied Force (N)'; 
                if(strcmp(m,'1'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 1 (N)');
                end
                if(strcmp(m,'2'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 2 (N)');                  
                end
                if(strcmp(m,'1 & 2'))
                    ss=sprintf('The input array must have two columns: \n Time(sec) & Force 1 & Force 2 (N)');                 
                end 
                app.InputArbitraryForceArray.Text=ss;     
                app.InputArrayNamePSD.Text='The PSD input array must have two columns:  Freq(Hz) & Force(N^2/Hz)';                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            app.SaveArbitraryButton.Enable='off';     
            app.SaveButton_Trans.Enable='off';
            app.SaveButton_PSD.Enable='off';
            app.SaveHSButton.Enable='off';
            app.SaveDiracButton.Enable='off';
            
            ChangeDamping(app);
            ChangeUnits(app);
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [cmap]=cmap_colors();
 
            hold(app.UIAxes,'on');

            x=[-4 -4 4 4 -4];
            y=[3 6 6 3 3]-3;
 
            plot(app.UIAxes,x,y,'Color',cmap(1,:),'linewidth',0.75);

            x=[-4 -4 4 4 -4];
            y=[3 6 6 3 3]+4;
 
            plot(app.UIAxes,x,y,'Color',cmap(1,:),'linewidth',0.75);

            xlim(app.UIAxes,[-12 12]);
            ylim(app.UIAxes,[-5-0.5 13-0.5]);

            %%%%%% text %%%%%%%%%%%%
            
            text(app.UIAxes,-3.5,11.25,'F_{2}','fontsize',13.5);
            text(app.UIAxes,3.5,4.5,'F_{1}','fontsize',13.5);
            
            text(app.UIAxes,7,3,'x_{1}','fontsize',13.5);
            text(app.UIAxes,7,10,'x_{2}','fontsize',13.5);
 
            text(app.UIAxes,-0.9,8.5,'m_{2}','fontsize',13.5);
            text(app.UIAxes,-0.9,1.5,'m_{1}','fontsize',13.5);
            text(app.UIAxes,-4,5,'k_{2}','fontsize',13.5);
            text(app.UIAxes,-4,-2,'k_{1}','fontsize',13.5);
            
            %%%%%% base %%%%%%%%%%%

            x=[-5 5];
            y=[ 1 1]-5;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            y=[0.5 1]-5;
            x=[-0.7 0];
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-1.4;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-2.8;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]-4.2;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+1.4;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+2.8;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);
            x=[-0.7 0]+4.2;
            plot(app.UIAxes,x,y,'Color','k','linewidth',0.75);

            %%%%%% side lines %%%%%%%%%%%%
 
            x=[4 6];
            y=[1.5 1.5];
            plot(app.UIAxes,x,y,'Color','k');
 
            x=[4 6];
            y=[8.5 8.5];
            plot(app.UIAxes,x,y,'Color','k');

            %%%%%% arrows %%%%%%%%%%%%
            
            x=[2.5 2.5];
            y=[7 8.25]-4;
            plot(app.UIAxes,x,y,'Color','k');            
            x=[2.5-0.3 2.5+0.3 2.5+0 2.5-0.3];
            y=[8.25-0.3 8.25-0.3 8.25 8.25-0.3]-4;
            plot(app.UIAxes,x,y,'Color','k');              
            
            x=[0 0];
            y=[7 8.25]+3;
            plot(app.UIAxes,x,y,'Color','k');
            
            x=[-0.3 0.3 0 -0.3];
            y=[8.25-0.3 8.25-0.3 8.25 8.25-0.3]+3;
            plot(app.UIAxes,x,y,'Color','k');            
 
            x=[6 6];
            y=[7 8.75]+1.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6-0.3 6+0.3 6 6-0.3];
            y=[8.5 8.5 8.5+0.3 8.5]+1.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6 6];
            y=[7 8.75]-5.5;
            plot(app.UIAxes,x,y,'Color','k');

            x=[6-0.3 6+0.3 6 6-0.3];
            y=[8.5 8.5 8.5+0.3 8.5]-5.5;
            plot(app.UIAxes,x,y,'Color','k');

            %%%%%% spring %%%%%%%%%%%%        
 
            nn=2000;
 
            dt=4/(nn-1);
 
            t=zeros(nn,1);
            for i=1:nn
                t(i)=(i-1)*dt;
            end
            t=t-0.75;
            y=sawtooth(2*pi*t,0.5);
 
            t=3*t/(max(t)-min(t))+2;
 
            plot(app.UIAxes,y,t+2,'Color',cmap(5,:),'linewidth',0.75);
            plot(app.UIAxes,y,t+2-7,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[3 min(t+2)];
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[max(t)+2 max(t)+2.5];
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[3 min(t+2)]-7;
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            x=[0 0];
            y=[max(t)+2 max(t)+2.5]-7;
            plot(app.UIAxes,x,y,'Color',cmap(5,:),'linewidth',0.75);
 
            hold(app.UIAxes,'off');

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            if(contains(app.UnitsListBox.Value,'English'))
                app.iu=1;
            else
                app.iu=2;                
            end    
            
            m1=app.Mass1EditField.Value;
            k1=app.Stiffness1EditField.Value;
            m2=app.Mass2EditField.Value;
            k2=app.Stiffness2EditField.Value;
            
            app.mass=zeros(2,2);

            app.mass(1,1)=m1;
            app.mass(2,2)=m2;

            app.stiffness=[(k1+k2) -k2; -k2 k2];

            if(app.iu==1)
                app.mass=app.mass/386.;
            end

            app.mm=[app.mass(1,1); app.mass(2,2)];

            disp(' ');

            [app.fn,app.ModeShapes,app.pff,~]=tdof_fn_etr_results(app.mass,app.stiffness,app.iu);
            
            app.omegan=2*pi*app.fn;
            app.MST=app.ModeShapes';       

            msgbox('Results written to command window');
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq
        end

        % Value changed function: ListBoxQ
        function ListBoxQValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ListBoxUniform
        function ListBoxUniformValueChanged(app, event)
            ChangeDamping(app);    
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
            n = app.ListBoxQ.Value;
            m = app.ListBoxUniform.Value;
            
            if(contains(n,'Q'))
                if(contains(m,'Uniform'))
                     Q1=app.Mode1EditField.Value;
                     Q2=Q1;
                     app.damp1=1/(2*Q1);
                     app.damp2=app.damp1;
                else
                     Q1=app.Mode1EditField.Value;
                     Q2=app.Mode2EditField.Value;
                     app.damp1=1/(2*Q1);
                     app.damp2=1/(2*Q2);                
                end    
            else
                if(contains(m,'Uniform'))
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.damp1;
                     Q1=1/(2*app.damp1);
                     Q2=Q1;              
                else
                     app.damp1=app.Mode1EditField.Value;
                     app.damp2=app.Mode2EditField.Value;
                     Q1=1/(2*app.damp1);
                     Q2=1/(2*app.damp2);                   
                end                   
            end         
            
            app.dm=[app.damp1 app.damp2];
            
            fprintf('\n Damping Ratios \n Mode 1       2        \n');
            fprintf('    %6.3g  %6.3g  \n',app.damp1,app.damp2);


        end

        % Button pushed function: CalculateButton_Sine
        function CalculateButton_SinePushed(app, event)
        
            check(app);
            
            if(app.zflag==1)
                return;
            end
        
            str=app.AppliedForcedofListBox.Value;
            
            if(contains(str,'1'))
                k=1;
            else
                k=2;
            end
            
            freq=app.FreqHzEditField.Value; 
            amp=app.ForcelbfEditField.Value;
            
            if(amp<1.0e-06)
                warndlg('Enter Force > 0 ')
                return;    
            end
            
            
            fprintf('\n Applied Force \n');
            fprintf('   %8.4g %s  %8.4g Hz\n\n',amp,app.force_unit,freq);

            nrb=0;  % leave as zero

            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
            
            nf=1;
            
            tpi=2*pi;

            omn=tpi*fnv;
            omn2=omn.*omn;

            omega=zeros(nf,1);
            omega2=zeros(nf,1);

            for i=1:nf
                omega(i)=2*pi*freq(i);
                omega2(i)=(omega(i))^2;
            end

            app.d_f_mag=zeros(2,1);
            app.v_f_mag=zeros(2,1);
            app.a_f_mag=zeros(2,1);

            h_disp_complex=zeros(2,1);

            num_columns=2;

            for i=1:2

                iam=1;
                [H_disp_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_disp=abs(H_disp_force);
                app.d_f_mag(i)=amp*HM_disp;
                h_disp_complex(i)=H_disp_force;
      
                iam=2;
                [H_velox_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_velox=abs(H_velox_force);
                app.v_f_mag(i)=amp*HM_velox;

                iam=3;
                [H_accel_force]=...
                transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);

                if(app.iu==1)
                    scale=386;
                else
                    scale=9.81;
                end
    
                H_accel_force=H_accel_force/scale;
    
                HM_accel=abs(H_accel_force);
                app.a_f_mag(i)=amp*HM_accel;
    
            end    

            rd21=amp*abs(h_disp_complex(2)-h_disp_complex(1));

            disp(' ');
            if(app.iu==1)
                disp(' Response      dof 1   dof 2 ');    
                fprintf('    Disp(in)  %6.3g   %6.3g \n',app.d_f_mag(1),app.d_f_mag(2));
                fprintf(' Vel(in/sec)  %6.3g   %6.3g \n',app.v_f_mag(1),app.v_f_mag(2));
                fprintf('    Accel(G)  %6.3g   %6.3g \n',app.a_f_mag(1),app.a_f_mag(2));   
            else
                disp(' Response         dof 1   dof 2 ');    
                fprintf('       Disp(mm)  %6.3g   %6.3g \n',app.d_f_mag(1)*1000,app.d_f_mag(2)*1000);
                fprintf('    Vel(cm/sec)  %6.3g   %6.3g \n',app.v_f_mag(1)*100,app.v_f_mag(2)*100);
                fprintf('       Accel(G)  %6.3g   %6.3g \n',app.a_f_mag(1),app.a_f_mag(2));    
            end

            disp(' ');
            disp(' Relative Displacement (dof 2 - dof 1) ');
            if(app.iu==1)
                fprintf('    Disp(in)  %6.3g \n',rd21);
            else
                fprintf('    Disp(mm)  %6.3g \n',rd21*1000);    
            end
            disp(' ');
          
            msgbox('Calculation complete.  Output written to Matlab Command Window.');

        end

        % Button pushed function: SettoFirstModalFreqButton
        function SettoFirstModalFreqButtonPushed(app, event)
            try
                ff=str2double(sprintf('%8.4g',app.fn(1)));
                app.FreqHzEditField.Value=ff;           
            catch
                warndlg('Run Natural Frequency Tab')
                return;
            end
        end

        % Button pushed function: CalculateButton_Arbitrary
        function CalculateButton_ArbitraryPushed(app, event)
            check(app);
            
            close_figures(app);
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            
            

            fig_num=1;
            
            FS = app.InputArrayNameEditField.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end

            %%%          
             
            n=strtrim(app.AppliedForcedofListBox_Arbitrary.Value);    

            t=THM(:,1);

            nt=length(t);
            f1=zeros(nt,1);
            f2=zeros(nt,1);

            if(strcmp(n,'1'))
                f1=THM(:,2);
            end
            if(strcmp(n,'2'))
                f2=THM(:,2);
            end
            if(strcmp(n,'1 & 2'))
                f1=THM(:,2);
                f2=THM(:,3);
            end

            F=[f1 f2];

            %
            tmx=t(end);
            tmi=t(1);

            dur=tmx-tmi;

            dt=dur/(nt-1);
            sr=1/dt;
            %
            if(sr<10*max(app.fn))
                disp(' ');
                warndlg(' Warning: insufficient sample rate. ');
            end

            ndof=2;
            sys_dof=ndof;
            
            dampv=app.dm;

            [x,v,a,~,~,~]=ramp_invariant_force(app.ModeShapes,F,ndof,sys_dof,app.omegan,dampv,dt);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            t_string1='dof 1';
            t_string2='dof 2';

            %       
            figure(fig_num);
            fig_num=fig_num+1;

            dd=x;
            if(app.iu==2)
                dd=dd*1000;
            end

            plot(t,dd(:,1),t,dd(:,2));

            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Displacement');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Displacement(in) ');
                disp(' Peak Displacement (in) ')
                ylabel1='Disp(in)';    
            else
                ylabel(' Displacement(mm) ');
                disp(' Peak Displacement (mm)')
                ylabel1='Disp(mm)';      
            end
            
            fprintf('dof 1  %8.4g  %8.4g  \n',max(dd(:,1)),min(dd(:,1)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(dd(:,2)),min(dd(:,2)));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t dd(:,1)];
            data2=[ t dd(:,2)];
            
            xlabel2='Time(sec)';
            ylabel2=ylabel1;
            
            [fig_num]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            app.displacement=[t dd(:,1) dd(:,2)];
            app.FD1=[THM dd(:,1)];
            app.FD2=[THM dd(:,2)];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            rd=zeros(nt,1);
            
            for i=1:nt
                rd(i)=dd(i,1)-dd(i,2);
            end
                
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,rd);
            xlabel('Time(sec)');
            title('Relative Displacement (dof 2 - dof 1)');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Disp(in) ');
                disp(' Peak Relative Displacement (in) ')
            else
                ylabel(' Disp(mm) ');
                disp(' Peak Relative Displacement (mm)')
            end
            
            fprintf('dof 2 - dof 1  %8.4g  %8.4g  \n',max(rd),min(rd));
           
            app.rel_disp=[t rd];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,v(:,1),t,v(:,2));
            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Velocity');
            
            grid on;
            
            disp(' ');
            if(app.iu==1)
                ylabel(' Velocity(in/sec) ');
                disp(' Peak Velocity (in/sec) ')
                ylabel1='Vel(in/sec)';  
            else
                ylabel(' Velocity(mm/sec) ');
                disp(' Peak Velocity (mm/sec)')
                ylabel1='Vel(mm/sec)';  
            end
 
            fprintf('dof 1  %8.4g  %8.4g  \n',max(v(:,1)),min(v(:,1)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(v(:,2)),min(v(:,2)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t v(:,1)];
            data2=[ t v(:,2)];
            
            xlabel2='Time(sec)';
            ylabel2=ylabel1;
            
            [fig_num]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);

            app.velocity=[t v(:,1) v(:,2)];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            acc=a;
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            if(app.iu==1)
                acc=acc/386;
            else
                acc=acc/9.81;
            end
            plot(t,acc(:,1),t,acc(:,2));
            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Acceleration');
            ylabel(' Accel(G) ');
            grid on;
            disp(' ');
            disp(' Peak Acceleration (G) ');
        
            fprintf('dof 1  %8.4g  %8.4g  \n',max(acc(:,1)),min(acc(:,1)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(acc(:,2)),min(acc(:,2)));
            
            app.acceleration=[t acc(:,1) acc(:,2)];
            
            disp(' ');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data1=[ t acc(:,1)];
            data2=[ t acc(:,2)];
            
            xlabel2='Time(sec)';
            ylabel1='Accel(G)';
            ylabel2=ylabel1;
            
            [~]=subplots_two_linlin_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.SaveArbitraryButton.Enable='on';                          
                                          
            msgbox('Calculation complete.  Output written to Matlab Command Window.');            
            
        end

        % Button pushed function: SaveArbitraryButton
        function SaveArbitraryButtonPushed(app, event)
          
            output_name=app.OutputArrayNameEditField.Value; 
        
            n=strtrim(app.OutputTimeHistoryArrayListBox_Arbitrary.Value);

            if(strcmp(n,'Displacement 1 & 2'))
                data=app.displacement;
            end
            if(strcmp(n,'Relative Displacement 2-1'))
                data=app.rel_disp;                
            end
            if(strcmp(n,'Velocity 1 & 2'))
                data=app.velocity;                   
            end
            if(strcmp(n,'Acceleration 1 & 2'))
                data=app.acceleration;                
            end
            if(strcmp(n,'Force & Displacement 1'))
                data=app.FD1;                
            end
            if(strcmp(n,'Force & Displacement 2'))
                data=app.FD2;                
            end  
            if(strcmp(n,'Force & Displacement 1 & 2'))
                data=[app.FD1 app.FD2(:,3)];         
            end
     
            assignin('base',output_name, data);
            msgbox('Data saved');    

        end

        % Button pushed function: CalculateButton_Trans
        function CalculateButton_TransPushed(app, event)
            
            check(app);
            
            if(app.zflag==1)
                return;
            end
            
            fig_num=1;
             
            fmin=app.StartFreqHzEditField.Value;
            fmax=app.EndFreqHzEditField.Value;
             
            if(fmin<=0.01)
                fmin=0.01;
            end    

            tpi=2*pi;
   
            N=48;
    
            [freq,omega,nf]=frequencies_octave_spaced(fmin,fmax,N);
    
            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
           
            num_modes=length(fnv);

            omn=tpi*fnv;
            omn2=omn.*omn;

            omega2=zeros(nf,1);

            for i=1:nf
                omega2(i)=(omega(i))^2;
            end

            nrb=0; % leave as zero

            num_columns=num_modes;
            
            if(contains(app.ResponsedofListBox.Value,'1'))
                i=1;
            else
                i=2;
            end            
            if(contains(app.ForcedofListBox.Value,'1'))
                k=1;
            else
                k=2;
            end

            freq=fix_size(freq);

            iam=1;
                [H_disp_force]=...
                transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                HM_disp=abs(H_disp_force);
                HP_disp=-atan2(imag(H_disp_force),real(H_disp_force))*180/pi;
                ymax=max(HM_disp);
                ymin=min(HM_disp);
                [fig_num]=...
                transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_disp,HP_disp);
                app.d_f_mag=[freq HM_disp];
                app.d_f_mag_phase=[freq HM_disp HP_disp];
                app.d_f_mag_complex=[freq H_disp_force];
      
               iam=2;
                   [H_velox_force]=...
                   transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                   HM_velox=abs(H_velox_force);
                   HP_velox=-atan2(imag(H_velox_force),real(H_velox_force))*180/pi;
                   ymax=max(HM_velox);
                   ymin=min(HM_velox);    
                   [fig_num]=...
                   transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_velox,HP_velox);   
                   app.v_f_mag=[freq HM_velox];
                   app.v_f_mag_phase=[freq HM_velox HP_velox];
                   app.v_f_mag_complex=[freq H_velox_force];

               iam=3;
                   [H_accel_force]=...
                   transfer_core(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);

                   if(app.iu==1)
                       scale=386;
                   else
                       scale=9.81;
                   end
    
                   H_accel_force= H_accel_force/scale;

                   HM_accel=abs(H_accel_force); 
                   HP_accel=-atan2(imag(H_accel_force),real(H_accel_force))*180/pi;
                   ymax=max(HM_accel);
                   ymin=min(HM_accel);    
                   [fig_num]=...
                   transfer_from_modes_plots2(app.iu,iam,i,k,fig_num,fmin,fmax,ymin,ymax,freq,HM_accel,HP_accel);
                   app.a_f_mag=[freq HM_accel];
                   app.a_f_mag_phase=[freq HM_accel HP_accel];
                   app.a_f_mag_complex=[freq H_accel_force];

               %%%%%%
               
            app.SaveButton_Trans.Enable='on';
            
            msgbox('Calculation complete.  See External Plots.  ');             
        end

        % Button pushed function: SaveButton_Trans
        function SaveButton_TransPushed(app, event)
            m=strtrim(app.ExportTrans.Value);
            n=strtrim(app.ExportAmplitude.Value);
            output_name=strtrim(app.OutputArrayNameEditFieldTrans.Value);
                
            if(strcmp(m,'Freq & Magnitude'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag;          
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag;   
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag; 
                end                
            end
            if(strcmp(m,'Freq & Magnitude & Phase'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag_phase;        
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag_phase;    
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag_phase; 
                end                 
            end
            if(strcmp(m,'Freq & Complex Magnitude'))
                if(strcmp(n,'Acceleration/Force'))
                    data=app.a_f_mag_complex; 
                end
                if(strcmp(n,'Velocity/Force'))
                    data=app.v_f_mag_complex; 
                end
                if(strcmp(n,'Displacement/Force'))
                    data=app.d_f_mag_complex; 
                end                 
            end            
            
            assignin('base',output_name, data); 
            msgbox('Data saved');
    
        end

        % Button pushed function: CalculateButton_HalfSine
        function CalculateButton_HalfSinePushed(app, event)
            check(app);
            
            close_figures(app);
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');
            
            if(strcmp(app.AppliedForcedofListBox_HS.Value,'1'))
                p=1;
            else
                p=2;
            end
      
            F=app.AppliedHSForceEditField.Value;
            T=app.PulseDurationsecEditField.Value;

            dur=app.TotalDurationsecEditField.Value;

            sr1=30/T;
            sr2=30*max(app.fn);
            sr=max([sr1 sr2]);

            dt=1/sr;

            nt=round(dur/dt);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            damp=app.dm;
            
            B=zeros(2,1);
            alpha=zeros(2,1);
          
            B(p,1)=F;
            alpha(p,1)=T;
            alpha(p,1)=pi/alpha(p,1);
            
            x_init=zeros(2,1);
            v_init=zeros(2,1);

            nd=app.MST*app.mass*x_init;
            nv=app.MST*app.mass*v_init;

            omegad=zeros(2,1);
            omegan2=zeros(2,1);
            omegad2=zeros(2,1);            
            domegan=zeros(2,1);
            AA=zeros(2,1);
            AAT=zeros(2,1);

            U1=zeros(2,2);
            U2=zeros(2,2);

            V1=zeros(2,2);
            V2=zeros(2,2);
            den=zeros(2,2);
            C=zeros(2,2);
            W=zeros(2,2);

            alpha2=alpha.^2;

            for j=1:2

                omegad(j)=app.omegan(j)*sqrt(1-(damp(j))^2);
                domegan(j)=damp(j)*app.omegan(j);
                omegan2(j)=app.omegan(j)^2;
                omegad2(j)=omegad(j)^2;
                AA(j)=(nv(j)+domegan(j)*nd(j))/omegad(j);     

                for k=1:2

                    U1(j,k)= 2*domegan(j)*alpha(k);
                    U2(j,k)= 2*domegan(j)*omegad(j);

                    V1(j,k)= (alpha2(k)-omegan2(j));
                    V2(j,k)= (alpha2(k)-omegan2(j)*(1-2*damp(j)^2));

                    den(j,k)=(alpha2(k)-omegan2(j))^2+(2*domegan(j)*alpha(k))^2;
                    C(j,k)=app.MST(j,k)*B(k)/den(j,k);

                    W(j,k)=alpha(k)/omegad(j);
      
                end
            end

            n=zeros(2,nt);
            nvelox=zeros(2,1);
            naccel=zeros(2,1);

            x=zeros(nt,2);
            v=zeros(nt,2);
            accel=zeros(nt,2); 

            ndT=zeros(2,1);
            nvT=zeros(2,1);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Solve for initial conditions at end of pulse
            %
                 for j=1:2  % nodal dof
            %
                    omdt=omegad(j)*T;
                    cos_odt=cos(omdt);
                    sin_odt=sin(omdt);
            %      
                     n1=zeros(2,1);
                     n2=zeros(2,1);
                    nv1=zeros(2,1);
                    nv2=zeros(2,1);
                    na1=zeros(2,1);
                    na2=zeros(2,1);
            %
                      ee=exp(-domegan(j)*T);
                     dee=-domegan(j)*ee;
                    ddee=(domegan(j))^2*ee;
            %
                    for k=1:2  % forces
            %
                        alphat=alpha(k)*T; 
            %          
                        n1(k)=      (U1(j,k)*cos(alphat) + V1(j,k)*sin(alphat));
                        n2(k)=   ee*(U2(j,k)*cos_odt + V2(j,k)*sin_odt);
            %
                        n1(k)=n1(k)*C(j,k);
                        n2(k)=n2(k)*W(j,k)*C(j,k);
            %
                        nv1(k)=    alpha(k)*(-U1(j,k)*sin(alphat) + V1(j,k)*cos(alphat));
                        nv2(k)=omegad(j)*ee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)...
                                       +dee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);                 
            %
                        nv1(k)=nv1(k)*C(j,k);
                        nv2(k)=nv2(k)*W(j,k)*C(j,k);            
            %
                        na1(k)= alpha2(k)*(-U1(j,k)*cos(alphat) - V1(j,k)*sin(alphat));
                        na2(k)=    omegad2(j)*ee*(-U2(j,k)*cos_odt - V2(j,k)*sin_odt)...
                                 +2*omegad(j)*dee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)... 
                                 +           ddee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);             
            %  
                        na1(k)=na1(k)*C(j,k);
                        na2(k)=na2(k)*W(j,k)*C(j,k); 
            %
                    end  % forces
            %      
                    n3= ee*(nd(j)*cos_odt + AA(j)*sin_odt);
            %
                    nv3=           dee*( nd(j)*cos_odt + AA(j)*sin_odt)+...
                            omegad(j)*ee*(-nd(j)*sin_odt + AA(j)*cos_odt);
            %           
                    ndT(j)= -sum(n1)+sum(n2) + n3;
                    nvT(j)=-sum(nv1)+sum(nv2)+nv3;
                 end  % nodal dof
            %
                 for j=1:2
                    AAT(j)=(nvT(j)+domegan(j)*ndT(j))/omegad(j); 
                 end         
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                t=(1:nt)*dt-dt;
                t=fix_size(t);
                force=zeros(nt,1);
            %
                for i=1:nt
            %
                    if(t(i)<=T)
                        force(i)=B(p)*sin(alpha(p)*t(i));     
            %
                        for j=1:2  % nodal dof
            %
                            omdt=omegad(j)*t(i);
                            cos_odt=cos(omdt);
                            sin_odt=sin(omdt);
            %
                            n(j,i)=0;
                            nvelox(j)=0;
                            naccel(j)=0.;
            %      
                            n1=zeros(2,1);
                            n2=zeros(2,1);
                            nv1=zeros(2,1);
                            nv2=zeros(2,1);
                            na1=zeros(2,1);
                            na2=zeros(2,1);
            %
                            ee=exp(-domegan(j)*t(i));
                            dee=-domegan(j)*ee;
                            ddee=(domegan(j))^2*ee;
            %
                            for k=1:2  % forces
            %
                                alphat=alpha(k)*t(i); 
            %          
                                n1(k)=      (U1(j,k)*cos(alphat) + V1(j,k)*sin(alphat));
                                n2(k)=   ee*(U2(j,k)*cos_odt + V2(j,k)*sin_odt);
            %
                                n1(k)=n1(k)*C(j,k);
                                n2(k)=n2(k)*W(j,k)*C(j,k);
            %
                                nv1(k)=    alpha(k)*(-U1(j,k)*sin(alphat) + V1(j,k)*cos(alphat));
                                nv2(k)=omegad(j)*ee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)...
                                        +dee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);                 
            %
                                nv1(k)=nv1(k)*C(j,k);
                                nv2(k)=nv2(k)*W(j,k)*C(j,k);            
            %
                                na1(k)= alpha2(k)*(-U1(j,k)*cos(alphat) - V1(j,k)*sin(alphat));
                                na2(k)=    omegad2(j)*ee*(-U2(j,k)*cos_odt - V2(j,k)*sin_odt)...
                                        +2*omegad(j)*dee*(-U2(j,k)*sin_odt + V2(j,k)*cos_odt)... 
                                        +           ddee*( U2(j,k)*cos_odt + V2(j,k)*sin_odt);             
            %  
                                na1(k)=na1(k)*C(j,k);
                                na2(k)=na2(k)*W(j,k)*C(j,k); 
            %
                            end
            %      
                            n3= ee*(nd(j)*cos_odt + AA(j)*sin_odt);
            %
                            nv3= dee*( nd(j)*cos_odt + AA(j)*sin_odt)+...
                                 omegad(j)*ee*(-nd(j)*sin_odt + AA(j)*cos_odt);
            %
                            na3=-2*domegan(j)*nv3-omegan2(j)*n3;
            %           
                            n(j,i)= -sum(n1)+sum(n2) + n3;
                            nvelox(j)=-sum(nv1)+sum(nv2)+nv3;
                            naccel(j)=-sum(na1)+sum(na2)+na3;
                        end
            %     
                    else % free 
            %
                        for j=1:2
                            tau=t(i)-T;
            % 
                            omdt=omegad(j)*tau;
                            cos_odt=cos(omdt);
                            sin_odt=sin(omdt);

                            n(j,i)=0;
                            nvelox(j)=0;
                            naccel(j)=0.;
            %
                              ee=exp(-domegan(j)*tau);
                             dee=-domegan(j)*ee;
            %
                            n3= ee*(ndT(j)*cos_odt + AAT(j)*sin_odt);
            %
                            nv3= dee*( ndT(j)*cos_odt + AAT(j)*sin_odt)+...
                                    omegad(j)*ee*(-ndT(j)*sin_odt + AAT(j)*cos_odt);
            %
                            na3=-2*domegan(j)*nv3-omegan2(j)*n3;
            %
                            n(j,i)=  n3;
                            nvelox(j)= nv3;
                            naccel(j)= na3;
            %
                        end
                    end
            %   
                        x(i,:)=(app.ModeShapes*n(:,i))';
                        v(i,:)=(app.ModeShapes*nvelox)';
                    accel(i,:)=(app.ModeShapes*naccel)';   
                end
            %
                if(app.iu==1)
                    accel=accel/386.;
                end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            if(app.iu==1)
                disp(' Peak Displacement (in) ')
            else
                disp(' Peak Displacement (m)')
            end
            fprintf('dof 1  %8.4g   %8.4g  \n',max(x(:,1)),min(x(:,1)));
            fprintf('dof 2  %8.4g   %8.4g  \n',max(x(:,2)),min(x(:,2)));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            rd=x(:,2)-x(:,1);
            disp(' ');
            if(app.iu==1)
                disp(' Peak Relative Displacement (in) ')
            else
                disp(' Peak Relative Displacement (mm)')
            end
            fprintf('dof 2 - dof 1  %8.4g   %8.4g  \n',max(rd),min(rd));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            disp(' ');
            if(app.iu==1)
                disp(' Peak Velocity (in/sec) ')
            else
                disp(' Peak Velocity (m/sec)')
            end
            fprintf('dof 1  %8.4g   %8.4g  \n',max(v(:,1)),min(v(:,1)));
            fprintf('dof 2  %8.4g   %8.4g  \n',max(v(:,2)),min(v(:,2)));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %     
            disp(' ');
            if(app.iu==1)
                disp(' Peak Acceleration (G) ')
            else
                disp(' Peak Acceleration (m/sec^2)')
            end
            fprintf('dof 1  %8.4g   %8.4g  \n',max(accel(:,1)),min(accel(:,1)));
            fprintf('dof 2  %8.4g   %8.4g  \n',max(accel(:,2)),min(accel(:,2)));
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            figure(1);
            plot(t,x(:,1),t,x(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Disp (inch)');
            else
                ylabel('Disp (m)');
            end
            title('Displacement');
            legend ('dof 1','dof 2');  
            grid on;
            %
            figure(2);
            clear rd;
            rd=x(:,2)-x(:,1);
            plot(t,rd);
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Rel Disp (inch)');
            else
                ylabel('Rel Disp (m)');
            end
            title('Relative Displacement (dof 2 - dof 1)');  
            grid on;
            %
            figure(3);
            plot(t,v(:,1),t,v(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Vel (in/sec)');
            else
                ylabel('Vel (m/sec)');
            end
            title('Velocity');
            legend ('dof 1','dof 2');  
            grid on;
            %
            figure(4);
            plot(t,accel(:,1),t,accel(:,2));
            xlabel('Time(sec)');
            if(app.iu==1)
                ylabel('Accel (G)');
            else
                ylabel('Accel (m/sec^2)');
            end
            title('Acceleration');
            legend ('dof 1','dof 2'); 
            grid on;
            %
            app.displacement=[t,x(:,1),x(:,2)];
            app.velocity=[t,v(:,1),v(:,2)];
            app.acceleration=[t,accel(:,1),accel(:,2)];
            app.rel_disp=[t,rd];
            %
            app.FD1=[t  force   x(:,1)];
            app.FD2=[t  force   x(:,2)];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            app.SaveHSButton.Enable='on';
            
            msgbox('Calculation complete.  See external plots.');            
        end

        % Button pushed function: CalculateButton_PSD
        function CalculateButton_PSDPushed(app, event)

            check(app);
            
            close_figures(app);
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            

            fig_num=1;

            FS=app.InputArrayNameEditField_PSD.Value;
            
            try
                THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end
            
            %
            THM=fix_size(THM);
            %
            sz=size(THM);
            n=sz(1);
            %
            fstart=THM(1,1);
            fend=THM(n,1);
            %
            if(fstart<=0)
                warndlg('Error: starting frequency must be >0 ');
                return;
            end
 
            tpi=2*pi;

            num=length(THM(:,1));
            
            fmin=THM(1,1);
            fmax=THM(num,1);
            
            N=48;
            %
            [freq,omega,~]=frequencies_octave_spaced(fmin,fmax,N);
            
            [fpsd]=interp_psd_oct(THM(:,1),THM(:,2),freq);
            
            %%%%%%%%%%%%%%%%
            %
            QE=app.ModeShapes;
            fnv=app.fn;
            dampv=app.dm;
            nf=length(freq);
            num_modes=length(fnv);
            %
            omn=tpi*fnv;
            omn2=omn.*omn;
            %
            omega2=zeros(nf,1);
            %
            for i=1:nf
                omega2(i)=(omega(i))^2;
            end
            %
            nrb=0;
            for i=1:num_modes
                if(app.fn(i)<0.001)
                    nrb=nrb+1;
                end
            end
            %
            if(nrb>=1)
                fprintf('\n  %d Rigid-body modes detected. \n\n Rigid-body modes will be suppressed. \n',nrb);
            end
            
            %%%%%%
            
            freq=fix_size(freq);
            numf=length(freq);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              
            num_columns=2;
            
            kd=strtrim(app.AppliedForcedofListBox_PSD.Value);
            
            H_disp_force_complex=zeros(numf,2,2);
            H_velox_force_complex=zeros(numf,2,2);
            H_accel_force_complex=zeros(numf,2,2);
            
            for i=1:2
                
                for k=1:2
            %    
                    iam=1;
                    [H_disp_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
            
                    if(app.iu==2)
                        H_disp_force=H_disp_force*1000;
                    end   
                
                    H_disp_force_complex(:,i,k)=H_disp_force;
            %    
                    iam=2;
                    [H_velox_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
                    H_velox_force_complex(:,i,k)=H_velox_force;
               
                    iam=3;
                    [H_accel_force]=transfer_core_np(freq,fnv,dampv,QE,omn2,omega,omega2,iam,nf,num_columns,i,k,nrb);
            
                    if(app.iu==1)
                        scale=386;
                    else
                        scale=9.81;
                    end
                
                    H_accel_force_complex(:,i,k)=H_accel_force/scale;
                
                end
                
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_psd=zeros(numf,2);   
            vel_psd=zeros(numf,2);    
            disp_psd=zeros(numf,2);    
            disp_psd_complex=zeros(numf,2); 
            rel_disp_psd=zeros(numf,1);
            
            if(strcmp(kd,'1'))
                    S=[1 0; 0 0]
            end
            if(strcmp(kd,'2'))
                    S=[0 0; 0 1]
            end    
            if(strcmp(kd,'1 & 2 Fully Correlated'))
                    S=[1 1; 1 1]
            end
            if(strcmp(kd,'1 & 2 Fully Uncorrelated'))
                    S=[1 0; 0 1]
            end     
            
            for i=1:numf
                  
                S=S*fpsd(i);
            
            %    
                H=[H_accel_force_complex(i,1,1)  H_accel_force_complex(i,1,2); H_accel_force_complex(i,2,1)  H_accel_force_complex(i,2,2) ];
                HC=conj(H);
                
                size(H);
                size(S);
                size(HC);
                
                a=H*S*HC';
            %
                H=[H_velox_force_complex(i,1,1)  H_velox_force_complex(i,1,2); H_velox_force_complex(i,2,1)  H_velox_force_complex(i,2,2) ];
                HC=conj(H);
                v=H*S*HC';
            %
                H=[H_disp_force_complex(i,1,1)  H_disp_force_complex(i,1,2); H_disp_force_complex(i,2,1)  H_disp_force_complex(i,2,2) ];
                HC=conj(H);
                d=H*S*HC';
            %
                accel_psd(i,:)=abs(diag(a));
                vel_psd(i,:)=abs(diag(v));
                disp_psd(i,:)=abs(diag(d));
            
                disp_psd_complex(i,:)=diag(d);
                
            end
            
            dr=zeros(2,1);
            vr=zeros(2,1);
            ar=zeros(2,1);
             
            for j=1:2
                
                for i=1:numf
                     
                     if(j==1)
                        rel_disp_psd(i)=disp_psd_complex(i,2)-disp_psd_complex(i,1);      
                     end
                end
                
                [~,drms] = calculate_PSD_slopes_no(freq,disp_psd(:,j));
                [~,vrms] = calculate_PSD_slopes_no(freq,vel_psd(:,j));
                [~,arms] = calculate_PSD_slopes_no(freq,accel_psd(:,j));
                
                dr(j)=drms;
                vr(j)=vrms;
                ar(j)=arms;
                
            end
            
            rel_disp_psd=abs(rel_disp_psd);
            
            [~,rdr] = calculate_PSD_slopes_no(freq,rel_disp_psd);
            
            %%%%%%%%%%%%%%%%
            
            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                y_label_d='Disp (in^2/Hz)';  
                y_label_v='Vel ((in/sec)^2/Hz)'; 
                
                t_string_d='Displacement PSD';
                t_string_v='Velocity PSD';
                
                t_string_rd=sprintf(' Relative Displacement PSD %6.3g inch RMS',rdr);    
                
            else
                y_label_d='Disp (mm^2/Hz)'; 
                y_label_v='Vel ((m/sec)^2/Hz)';  
                
                t_string_d='Displacement PSD';
                t_string_v='Velocity PSD';   
                
                t_string_rd=sprintf(' Relative Displacement PSD %6.3g mm RMS',rdr);      
            end
            
            y_label_a='Accel (G^2/Hz)';
            t_string_a='Acceleration PSD';
            
            freq=fix_size(freq);
            
            rel_disp_psd=[freq rel_disp_psd];
                disp_psd=[freq disp_psd];
                 vel_psd=[freq vel_psd];
               accel_psd=[freq accel_psd];

            md=6;
            [fig_num,h2]=plot_loglog_function_md_h2(fig_num,x_label,y_label_d,t_string_rd,rel_disp_psd,fmin,fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ppp=[disp_psd(:,1) disp_psd(:,2) ];
            qqq=[disp_psd(:,1) disp_psd(:,3) ];
            
            if(app.iu==1)
                leg_a=sprintf('dof 1 %6.3g in RMS',dr(1));
                leg_b=sprintf('dof 2 %6.3g in RMS',dr(2));
            else
                leg_a=sprintf('dof 1 %6.3g mm RMS',dr(1));
                leg_b=sprintf('dof 2 %6.3g mm RMS',dr(2));    
            end
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_d,t_string_d,ppp,qqq,leg_a,leg_b);
            
            ppp=[vel_psd(:,1) vel_psd(:,2) ];
            qqq=[vel_psd(:,1) vel_psd(:,3) ];
            
            if(app.iu==1)
                leg_a=sprintf('dof 1 %6.3g in/sec RMS',vr(1));
                leg_b=sprintf('dof 2 %6.3g in/sec RMS',vr(2));
            else
                leg_a=sprintf('dof 1 %6.3g mm/sec RMS',vr(1));
                leg_b=sprintf('dof 2 %6.3g mm/sec RMS',vr(2));   
            end
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_v,t_string_v,ppp,qqq,leg_a,leg_b);     
                           
            ppp=[accel_psd(:,1) accel_psd(:,2) ];
            qqq=[accel_psd(:,1) accel_psd(:,3) ];
            
            leg_a=sprintf('dof 1 %6.3g GRMS',ar(1));
            leg_b=sprintf('dof 2 %6.3g GRMS',ar(2));
            
            [fig_num,h2]=...
                  plot_PSD_two_h2(fig_num,x_label,y_label_a,t_string_a,ppp,qqq,leg_a,leg_b);     
                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.acceleration=accel_psd;
            app.velocity=vel_psd;    
            app.displacement=disp_psd; 
            app.rel_disp=rel_disp_psd;
                   
            app.FD1=[ freq fpsd disp_psd(:,2)];
            app.FD2=[ freq fpsd disp_psd(:,3)];
            
            app.SaveButton_PSD.Enable='on';   
        end

        % Button pushed function: SaveButton_PSD
        function SaveButton_PSDPushed(app, event)

            n=strtrim(app.OutputArrayListBox.Value);    
 
            try
                
                if(strcmp(n,'Displacement 1 & 2'))
                    data=app.displacement;
                end
                if(strcmp(n,'Relative Displacement 2-1'))
                    data=app.rel_disp;                
                end
                if(strcmp(n,'Velocity 1 & 2'))
                    data=app.velocity;                   
                end
                if(strcmp(n,'Acceleration 1 & 2'))
                    data=app.acceleration;                
                end
                if(strcmp(n,'Force & Displacement 1'))
                    data=app.FD1;                
                end
                if(strcmp(n,'Force & Displacement 2'))
                    data=app.FD2;                
                end  
                if(strcmp(n,'Force & Displacement 1 & 2'))
                    data=[app.FD1 app.FD2(:,3)];         
                end
            
                output_name=app.OutputArrayNameEditField_PSD.Value;
                assignin('base', output_name, data);

                msgbox('Save Complete'); 
            catch
                warndlg('Output files not written')
                return;
            end
        end

        % Button pushed function: CalculateButton_Dirac
        function CalculateButton_DiracPushed(app, event)
            
            check(app);
            
            close_figures(app);
            
            if(app.zflag==1)
                return;
            end
            
            disp(' ');
            disp(' * * * * * ')
            disp(' ');            

            fig_num=1;

            damp=app.dm;
  
            dur=app.TotalDurationsecEditField_Dirac.Value;
            
            sr=app.SampleRateHzEditField_Dirac.Value;   
            
            dt=1/sr;
            
            nt=round(dur/dt);
            
            Q=app.ModeShapes;
            
            nd=zeros(2,nt);
            d=zeros(2,nt);
            
            nv=zeros(2,nt);
            v=zeros(2,nt);
            
            na=zeros(2,nt);
            acc=zeros(2,nt);
            
            t=zeros(nt,1);
            
            Imp=app.TotalImpulselbfsecEditField_Dirac.Value;
            
            %%%
               
            omegad=zeros(2,1);
            domegan=zeros(2,1);
            
            for i=1:2
                omegad(i)=app.omegan(i)*sqrt(1-damp(i)^2);
               domegan(i)=damp(i)*app.omegan(i);
            end
            
            if(max(abs(damp))>1)
               warndlg('Damping Error'); 
               return; 
            end
            
            for i=1:nt
                
               t(i)=(i-1)*dt;   
                  
               a1=-domegan(1)*t(i);
               b1=  omegad(1)*t(i);
               
               a2=-domegan(2)*t(i);
               b2=  omegad(2)*t(i);
               
               sinb1=sin(b1);   
               sinb2=sin(b2);   
               
               cosb1=cos(b1);
               cosb2=cos(b2);   
                 
               n1= Q(2,1)*exp(a1)*sinb1/omegad(1); 
               n2= Q(2,2)*exp(a2)*sinb2/omegad(2);    
            
               nd(1,i)=Imp*n1;
               nd(2,i)=Imp*n2;
               d(:,i)=Q*nd(:,i);
               
            %%%   
            
               n1= Q(2,1)*( -domegan(1)*sinb1+omegad(1)*cosb1 )*exp(a1)/omegad(1); 
               n2= Q(2,2)*( -domegan(2)*sinb2+omegad(2)*cosb2 )*exp(a2)/omegad(2);   
            
               nv(1,i)=Imp*n1;
               nv(2,i)=Imp*n2;
               v(:,i)=Q*nv(:,i);
            
            %%%
            
               n11=  -domegan(1)*( -domegan(1)*sinb1+omegad(1)*cosb1 );
               n12=    omegad(1)*( -domegan(1)*cosb1-omegad(1)*sinb1 );
            
               n21=  -domegan(2)*( -domegan(2)*sinb2+omegad(2)*cosb2 );
               n22=    omegad(2)*( -domegan(2)*cosb2-omegad(2)*sinb2 );  
              
               na(1,i)=Imp*Q(2,1)*(n11+n12)*exp(a1)/omegad(1);
               na(2,i)=Imp*Q(2,2)*(n21+n22)*exp(a2)/omegad(2);
               
               acc(:,i)=Q*na(:,i);   
               
            %%%
            end
            %
            
            data1=[t nd(1,:)'];
            data2=[t nd(2,:)'];
            
            t_string1='Modal Displacements';
            t_string2=' ';
            
            xlabel2='Time (sec)';
            
            ylabel1=' mode 1 ';
            ylabel2=' mode 2 ';
            
            [fig_num]=subplots_two_linlin_two_titles_scale(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2);
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            
            dd=d;
            if(app.iu==2)
                dd=dd*1000;
            end
            
            plot(t,dd(1,:),t,dd(2,:));

            legend ('dof 1','dof 2');   
            xlabel('Time(sec)');
            title('Displacement');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Displacement (in) ');
                disp(' Peak Displacement (in) ')
            else
                ylabel(' Displacement (mm) ');
                disp(' Peak Displacement (mm)')
            end
            
            fprintf('dof 1  %8.4g  %8.4g  \n',max(dd(1,:)),min(dd(1,:)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(dd(2,:)),min(dd(2,:)));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            dd=fix_size(dd);
            
            rd=zeros(nt,1);
            
            for i=1:nt
                rd(i)=dd(i,2)-dd(i,1);
            end
                
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,rd);
            xlabel('Time (sec)');
            title('Relative Displacement (dof 2 - dof 1)');
            grid on;
            disp(' ');
            if(app.iu==1)
                ylabel(' Disp (in) ');
                disp(' Peak Relative Displacement (in) ')
            else
                ylabel(' Disp (mm) ');
                disp(' Peak Relative Displacement (mm)')
            end
            
            fprintf('dof 1  %8.4g  %8.4g  \n',max(rd),min(rd));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(t,v(1,:),t,v(2,:));
            legend ('dof 1','dof 2');   
            xlabel('Time (sec)');
            title('Velocity');
            grid on;
            %
            
            disp(' ');
            if(app.iu==1)
                ylabel(' Velocity (in/sec) ');
                disp(' Peak Velocity (in/sec) ')
            else
                ylabel(' Velocity (mm/sec) ');
                disp(' Peak Velocity (mm/sec)')
            end
             
            fprintf('dof 1  %8.4g  %8.4g  \n',max(v(1,:)),min(v(1,:)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(v(2,:)),min(v(2,:)));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            figure(fig_num);
            % fig_num=fig_num+1;
            if(app.iu==1)
                acc=acc/386;
            else
                acc=acc/9.81;
            end
            plot(t,acc(1,:),t,acc(2,:));
            legend ('dof 1','dof 2');   
            xlabel('Time (sec)');
            title('Acceleration');
            ylabel(' Accel (G) ');
            grid on;
            disp(' ');
            disp(' Peak Acceleration (G) ');
                    
            fprintf('dof 1  %8.4g  %8.4g  \n',max(acc(1,:)),min(acc(1,:)));
            fprintf('dof 2  %8.4g  %8.4g  \n',max(acc(2,:)),min(acc(2,:)));

            disp(' ');
            
            v=fix_size(v);
            acc=fix_size(acc);

            app.displacement=[t, dd(:,1), dd(:,2)];
            app.rel_disp=[t rd];
         
            app.velocity=[t v(:,1) v(:,2)];
            app.acceleration=[t acc(:,1) acc(:,2)];

            app.SaveDiracButton.Enable='on';
            
            msgbox('Results written to Command Window');

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            ChangeUnits(app);
        end

        % Button pushed function: SaveHSButton
        function SaveHSButtonPushed(app, event)
            n=strtrim(app.OutputTimeHistoryArrayListBox.Value);
            if(strcmp(n,'Displacement 1 & 2'))
                data=app.displacement;
            end
            if(strcmp(n,'Relative Displacement 2-1'))
                data=app.rel_disp;                
            end
            if(strcmp(n,'Velocity 1 & 2'))
                data=app.velocity;                   
            end
            if(strcmp(n,'Acceleration 1 & 2'))
                data=app.acceleration;                
            end
            if(strcmp(n,'Force & Displacement 1'))
                data=app.FD1;                
            end
            if(strcmp(n,'Force & Displacement 2'))
                data=app.FD2;                
            end  
            if(strcmp(n,'Force & Displacement 1 & 2'))
                data=[app.FD1 app.FD2(:,3)];                
            end
            output_name=strtrim(app.OutputArrayNameEditField_HS.Value);
            assignin('base',output_name, data); 
            msgbox('Data saved');
   
        end

        % Value changed function: AppliedForcedofListBox_HS
        function AppliedForcedofListBox_HSValueChanged(app, event)

            app.SaveHSButton.Enable='off';
        end

        % Button pushed function: ModalToolBoxButton
        function ModalToolBoxButtonPushed(app, event)
            modal_toolbox;
        end

        % Value changed function: AppliedForcedofListBox_Arbitrary
        function AppliedForcedofListBox_ArbitraryValueChanged(app, event)
            ChangeUnits(app);
        end

        % Button pushed function: SaveDiracButton
        function SaveDiracButtonPushed(app, event)
            output_name=app.OutputArrayNameEditField_Dirac.Value; 
        
            n=strtrim(app.OutputTimeHistoryArrayListBox_Dirac.Value);

            if(strcmp(n,'Displacement 1 & 2'))
                data=app.displacement;
            end
            if(strcmp(n,'Relative Displacement 2-1'))
                data=app.rel_disp;                
            end
            if(strcmp(n,'Velocity 1 & 2'))
                data=app.velocity;                   
            end
            if(strcmp(n,'Acceleration 1 & 2'))
                data=app.acceleration;                
            end
     
            assignin('base',output_name, data);
            msgbox('Data saved');   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UITwodofForcebbUIFigure = uifigure('Visible', 'off');
            app.UITwodofForcebbUIFigure.Name = 'UI Two dof System Force bb';
            app.TabGroup = uitabgroup(app.UITwodofForcebbUIFigure);
            app.NaturalFreqTab = uitab(app.TabGroup);
            app.NaturalFreqTab.Title = 'Natural Freq';
            app.Mass2EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Mass2EditFieldLabel.Text = 'Mass 2';
            app.Mass2EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Mass1EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Mass1EditFieldLabel.Text = 'Mass 1';
            app.Mass1EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Stiffness2EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Stiffness2EditFieldLabel.Text = 'Stiffness 2';
            app.Stiffness2EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.Stiffness1EditFieldLabel = uilabel(app.NaturalFreqTab);
            app.Stiffness1EditFieldLabel.Text = 'Stiffness 1';
            app.Stiffness1EditField = uieditfield(app.NaturalFreqTab, 'numeric');
            app.UnitsListBoxLabel = uilabel(app.NaturalFreqTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.NaturalFreqTab);
            app.UnitsListBox.Items = {'English:  mass (lbm) & stiffness (lbf/in)', 'Metric:    mass (kg) & stiffness (N/m)', ''};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'English:  mass (lbm) & stiffness (lbf/in)';
            app.CalculateButton = uibutton(app.NaturalFreqTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.SelectTypesLabel = uilabel(app.DampingTab);
            app.SelectTypesLabel.Text = 'Select Types';
            app.Label = uilabel(app.DampingTab);
            app.Label.Text = '';
            app.ListBoxQ = uilistbox(app.DampingTab);
            app.ListBoxQ.Items = {'Amplification Factor Q', 'Viscous Damping Coefficient'};
            app.ListBoxQ.ValueChangedFcn = createCallbackFcn(app, @ListBoxQValueChanged, true);
            app.ListBoxQ.Value = 'Amplification Factor Q';
            app.Label_2 = uilabel(app.DampingTab);
            app.Label_2.Text = '';
            app.ListBoxUniform = uilistbox(app.DampingTab);
            app.ListBoxUniform.Items = {'Uniform', 'Varies by Mode'};
            app.ListBoxUniform.ValueChangedFcn = createCallbackFcn(app, @ListBoxUniformValueChanged, true);
            app.ListBoxUniform.Value = 'Uniform';
            app.Mode1EditFieldLabel = uilabel(app.DampingTab);
            app.Mode1EditFieldLabel.Text = 'Mode 1';
            app.Mode1EditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingUnitLabel = uilabel(app.DampingTab);
            app.DampingUnitLabel.Text = 'DampingUnit';
            app.Mode2EditFieldLabel = uilabel(app.DampingTab);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.DampingTab, 'numeric');
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.TransFRFTab = uitab(app.TabGroup);
            app.TransFRFTab.Title = 'Trans FRF';
            app.CalculateButton_Trans = uibutton(app.TransFRFTab, 'push');
            app.CalculateButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_TransPushed, true);
            app.CalculateButton_Trans.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.TransFRFTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.TransFRFTab, 'numeric');
            app.EndFreqHzEditFieldLabel = uilabel(app.TransFRFTab);
            app.EndFreqHzEditFieldLabel.Text = {'End Freq (Hz)'; ''};
            app.EndFreqHzEditField = uieditfield(app.TransFRFTab, 'numeric');
            app.SaveButton_Trans = uibutton(app.TransFRFTab, 'push');
            app.SaveButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_TransPushed, true);
            app.SaveButton_Trans.Text = 'Save';
            app.Label_3 = uilabel(app.TransFRFTab);
            app.Label_3.Text = '';
            app.ExportTrans = uilistbox(app.TransFRFTab);
            app.ExportTrans.Items = {'Freq & Magnitude', 'Freq & Magnitude & Phase', 'Freq & Complex Magnitude'};
            app.ExportTrans.Value = 'Freq & Magnitude';
            app.ExportFormatLabel = uilabel(app.TransFRFTab);
            app.ExportFormatLabel.Text = 'Export Format';
            app.Label_4 = uilabel(app.TransFRFTab);
            app.Label_4.Text = '';
            app.ExportAmplitude = uilistbox(app.TransFRFTab);
            app.ExportAmplitude.Items = {'Acceleration/Force', 'Velocity/Force', 'Displacement/Force'};
            app.ExportAmplitude.Value = 'Acceleration/Force';
            app.AmplitudeTypeLabel = uilabel(app.TransFRFTab);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.OutputArrayNameEditField_2Label = uilabel(app.TransFRFTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditFieldTrans = uieditfield(app.TransFRFTab, 'text');
            app.ForcedofListBoxLabel = uilabel(app.TransFRFTab);
            app.ForcedofListBoxLabel.Text = 'Force dof';
            app.ForcedofListBox = uilistbox(app.TransFRFTab);
            app.ForcedofListBox.Items = {'1', '2'};
            app.ForcedofListBox.Value = '1';
            app.ResponsedofListBoxLabel = uilabel(app.TransFRFTab);
            app.ResponsedofListBoxLabel.Text = 'Response dof';
            app.ResponsedofListBox = uilistbox(app.TransFRFTab);
            app.ResponsedofListBox.Items = {'1', '2'};
            app.ResponsedofListBox.Value = '1';
            app.SteadySineTab = uitab(app.TabGroup);
            app.SteadySineTab.Title = 'Steady Sine';
            app.ForcelbfEditFieldLabel = uilabel(app.SteadySineTab);
            app.ForcelbfEditFieldLabel.Text = 'Force (lbf)';
            app.ForcelbfEditField = uieditfield(app.SteadySineTab, 'numeric');
            app.SteadyStateAppliedForceLabel = uilabel(app.SteadySineTab);
            app.SteadyStateAppliedForceLabel.Text = 'Steady-State Applied Force';
            app.FreqHzEditFieldLabel = uilabel(app.SteadySineTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.SteadySineTab, 'numeric');
            app.CalculateButton_Sine = uibutton(app.SteadySineTab, 'push');
            app.CalculateButton_Sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SinePushed, true);
            app.CalculateButton_Sine.Text = 'Calculate';
            app.SettoFirstModalFreqButton = uibutton(app.SteadySineTab, 'push');
            app.SettoFirstModalFreqButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFirstModalFreqButtonPushed, true);
            app.SettoFirstModalFreqButton.Text = 'Set to First Modal Freq';
            app.AppliedForcedofListBoxLabel = uilabel(app.SteadySineTab);
            app.AppliedForcedofListBoxLabel.Text = 'Applied Force dof';
            app.AppliedForcedofListBox = uilistbox(app.SteadySineTab);
            app.AppliedForcedofListBox.Items = {'1', '2'};
            app.AppliedForcedofListBox.Value = '1';
            app.HalfSineTab = uitab(app.TabGroup);
            app.HalfSineTab.Title = 'Half-Sine';
            app.CalculateButton_HalfSine = uibutton(app.HalfSineTab, 'push');
            app.CalculateButton_HalfSine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_HalfSinePushed, true);
            app.CalculateButton_HalfSine.Text = 'Calculate';
            app.AppliedForcelbfLabel = uilabel(app.HalfSineTab);
            app.AppliedForcelbfLabel.Text = 'Applied Force (lbf)';
            app.AppliedHSForceEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.ResponsetoHalfSineAppliedForceLabel = uilabel(app.HalfSineTab);
            app.ResponsetoHalfSineAppliedForceLabel.Text = 'Response to Half-Sine Applied Force';
            app.PulseDurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.PulseDurationsecEditFieldLabel.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.AppliedForcedofListBox_2Label = uilabel(app.HalfSineTab);
            app.AppliedForcedofListBox_2Label.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_HS = uilistbox(app.HalfSineTab);
            app.AppliedForcedofListBox_HS.Items = {'1', '2'};
            app.AppliedForcedofListBox_HS.ValueChangedFcn = createCallbackFcn(app, @AppliedForcedofListBox_HSValueChanged, true);
            app.AppliedForcedofListBox_HS.Value = '1';
            app.SaveHSButton = uibutton(app.HalfSineTab, 'push');
            app.SaveHSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHSButtonPushed, true);
            app.SaveHSButton.Text = 'Save Output';
            app.OutputTimeHistoryArrayListBoxLabel = uilabel(app.HalfSineTab);
            app.OutputTimeHistoryArrayListBoxLabel.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox = uilistbox(app.HalfSineTab);
            app.OutputTimeHistoryArrayListBox.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputTimeHistoryArrayListBox.Value = 'Displacement 1 & 2';
            app.OutputArrayNameEditField_3Label = uilabel(app.HalfSineTab);
            app.OutputArrayNameEditField_3Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_HS = uieditfield(app.HalfSineTab, 'text');
            app.TotalDurationsecEditFieldLabel = uilabel(app.HalfSineTab);
            app.TotalDurationsecEditFieldLabel.Text = 'Total Duration (sec)';
            app.TotalDurationsecEditField = uieditfield(app.HalfSineTab, 'numeric');
            app.ArbitraryTab = uitab(app.TabGroup);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.CalculateButton_Arbitrary = uibutton(app.ArbitraryTab, 'push');
            app.CalculateButton_Arbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_ArbitraryPushed, true);
            app.CalculateButton_Arbitrary.Text = 'Calculate';
            app.InputArbitraryForceArray = uilabel(app.ArbitraryTab);
            app.InputArbitraryForceArray.Text = {'The input array must have two columns:  '; '  Time(sec) & Force (lbf)'};
            app.ResponsetoarbitraryappliedforceLabel = uilabel(app.ArbitraryTab);
            app.ResponsetoarbitraryappliedforceLabel.Text = 'Response to arbitrary applied force.  Ramp invariant digital recursive filtering relationship.';
            app.InputArrayNameEditFieldLabel = uilabel(app.ArbitraryTab);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.ArbitraryTab, 'text');
            app.SaveArbitraryButton = uibutton(app.ArbitraryTab, 'push');
            app.SaveArbitraryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArbitraryButtonPushed, true);
            app.SaveArbitraryButton.Text = 'Save';
            app.OutputTimeHistoryArrayListBox_2Label = uilabel(app.ArbitraryTab);
            app.OutputTimeHistoryArrayListBox_2Label.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox_Arbitrary = uilistbox(app.ArbitraryTab);
            app.OutputTimeHistoryArrayListBox_Arbitrary.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputTimeHistoryArrayListBox_Arbitrary.Value = 'Displacement 1 & 2';
            app.AppliedForcedofListBox_2Label_2 = uilabel(app.ArbitraryTab);
            app.AppliedForcedofListBox_2Label_2.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_Arbitrary = uilistbox(app.ArbitraryTab);
            app.AppliedForcedofListBox_Arbitrary.Items = {'1', '2', '1 & 2'};
            app.AppliedForcedofListBox_Arbitrary.ValueChangedFcn = createCallbackFcn(app, @AppliedForcedofListBox_ArbitraryValueChanged, true);
            app.AppliedForcedofListBox_Arbitrary.Value = '1';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ArbitraryTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ArbitraryTab, 'text');
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.CalculateButton_PSD = uibutton(app.PSDTab, 'push');
            app.CalculateButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_PSDPushed, true);
            app.CalculateButton_PSD.Text = 'Calculate';
            app.InputArrayNamePSD = uilabel(app.PSDTab);
            app.InputArrayNamePSD.Text = {'The PSD input array must have two columns:  Freq(Hz) & Force(lbf^2/Hz)'; ''};
            app.InputArrayNameEditField_3Label = uilabel(app.PSDTab);
            app.InputArrayNameEditField_3Label.Text = 'Input Array Name';
            app.InputArrayNameEditField_PSD = uieditfield(app.PSDTab, 'text');
            app.SaveButton_PSD = uibutton(app.PSDTab, 'push');
            app.SaveButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_PSDPushed, true);
            app.SaveButton_PSD.Text = 'Save';
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.PSDTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_PSD = uieditfield(app.PSDTab, 'text');
            app.OutputArrayListBoxLabel = uilabel(app.PSDTab);
            app.OutputArrayListBoxLabel.Text = 'Output Array';
            app.OutputArrayListBox = uilistbox(app.PSDTab);
            app.OutputArrayListBox.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2', 'Force & Displacement 1', 'Force & Displacement 2', 'Force & Displacement 1 & 2'};
            app.OutputArrayListBox.Value = 'Displacement 1 & 2';
            app.AppliedForcedofListBox_3Label = uilabel(app.PSDTab);
            app.AppliedForcedofListBox_3Label.Text = 'Applied Force dof';
            app.AppliedForcedofListBox_PSD = uilistbox(app.PSDTab);
            app.AppliedForcedofListBox_PSD.Items = {'1', '2', '1 & 2 Fully Correlated', '1 & 2 Fully Uncorrelated'};
            app.AppliedForcedofListBox_PSD.Value = '1';
            app.DiracDeltaTab = uitab(app.TabGroup);
            app.DiracDeltaTab.Title = 'Dirac Delta';
            app.CalculateButton_Dirac = uibutton(app.DiracDeltaTab, 'push');
            app.CalculateButton_Dirac.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_DiracPushed, true);
            app.CalculateButton_Dirac.Text = 'Calculate';
            app.ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel = uilabel(app.DiracDeltaTab);
            app.ThisscriptappliesaDiracdeltaimpulseforcetothetopmassLabel.Text = 'This script applies a Dirac delta impulse force to the top mass.  The solution method is the Laplace transform.';
            app.TotalDurationsecEditField_DiracLabel = uilabel(app.DiracDeltaTab);
            app.TotalDurationsecEditField_DiracLabel.Text = 'Total Duration (sec)';
            app.TotalDurationsecEditField_Dirac = uieditfield(app.DiracDeltaTab, 'numeric');
            app.SampleRateHzEditField_Label_Dirac = uilabel(app.DiracDeltaTab);
            app.SampleRateHzEditField_Label_Dirac.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_Dirac = uieditfield(app.DiracDeltaTab, 'numeric');
            app.TotalImpulselbfsecEditField_Label_Dirac = uilabel(app.DiracDeltaTab);
            app.TotalImpulselbfsecEditField_Label_Dirac.Text = 'Total Impulse (lbf sec)';
            app.TotalImpulselbfsecEditField_Dirac = uieditfield(app.DiracDeltaTab, 'numeric');
            app.SaveDiracButton = uibutton(app.DiracDeltaTab, 'push');
            app.SaveDiracButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDiracButtonPushed, true);
            app.SaveDiracButton.Text = 'Save';
            app.OutputTimeHistoryArrayListBox_Label_Dirace = uilabel(app.DiracDeltaTab);
            app.OutputTimeHistoryArrayListBox_Label_Dirace.Text = 'Output Time History Array';
            app.OutputTimeHistoryArrayListBox_Dirac = uilistbox(app.DiracDeltaTab);
            app.OutputTimeHistoryArrayListBox_Dirac.Items = {'Displacement 1 & 2', 'Relative Displacement 2-1', 'Velocity 1 & 2', 'Acceleration 1 & 2'};
            app.OutputTimeHistoryArrayListBox_Dirac.Value = 'Displacement 1 & 2';
            app.OutputArrayNameEditField_Label_Dirace = uilabel(app.DiracDeltaTab);
            app.OutputArrayNameEditField_Label_Dirace.Text = 'Output Array Name';
            app.OutputArrayNameEditField_Dirac = uieditfield(app.DiracDeltaTab, 'text');
            app.UIAxes = uiaxes(app.UITwodofForcebbUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.two_dof_base_aaver10byTomIrvineLabel = uilabel(app.UITwodofForcebbUIFigure);
            app.two_dof_base_aaver10byTomIrvineLabel.Text = {'two_dof_system_force_bb  ver 1.1  by Tom Irvine     Response of a two-dof system to applied force.'; ''};
            app.ReturnButton = uibutton(app.UITwodofForcebbUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ModalToolBoxButton = uibutton(app.UITwodofForcebbUIFigure, 'push');
            app.ModalToolBoxButton.ButtonPushedFcn = createCallbackFcn(app, @ModalToolBoxButtonPushed, true);
            app.ModalToolBoxButton.Text = 'Modal Toolbox';
            % Show the figure after all components are created
            app.UITwodofForcebbUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_dof_system_force_bb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UITwodofForcebbUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UITwodofForcebbUIFigure)
        end
    end
end