classdef velocity_spectrum_dB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVelocitySpectrumdBUIFigure  matlab.ui.Figure
        OutputVelocitySpectrumArrayNameEditField  matlab.ui.control.EditField
        OutputVelocitySpectrumArrayNameEditFieldLabel  matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        PlotFrequenciesHzLabel        matlab.ui.control.Label
        EndTimesecEditField           matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel      matlab.ui.control.Label
        StartTimesecEditField         matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel    matlab.ui.control.Label
        ProcessingListBox             matlab.ui.control.ListBox
        ProcessingListBoxLabel        matlab.ui.control.Label
        InputArrayNameEditField       matlab.ui.control.EditField
        InputArrayNameEditFieldLabel  matlab.ui.control.Label
        InputAmplitudeListBox         matlab.ui.control.ListBox
        InputAmplitudeListBoxLabel    matlab.ui.control.Label
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        ReturnButton                  matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        velocity_spectrum_dBver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM   % Description
        ref   %
        t1    %
        t2    %
        spl   %
        oaspl %
        mr    %
        window %
        iflag  %
    end
    
    methods (Access = private)
                
        function signal(app)
            value = app.ProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';            
            end              
        end
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end             
        end

        function preprocess(app)
            [~,i]=min(abs(app.t1-app.THM));
            [~,j]=min(abs(app.t2-app.THM));
        
            app.THM=app.THM(i:j,:);
        
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            if(app.iflag==999)
                return;
            end        
        end    
        
        function spl_function_alt_no(app)
            app.mr=1;      % mean removal
            app.window=2;  % Hanning window

            t=app.THM(:,1);
            amp=app.THM(:,2);
        
            %%%%%
        
            n=length(t);
            
            dur=t(end)-t(1);
            nj=floor(log2(n));
            
            njt=min([12 nj]);
            
                num_seg=zeros(njt,1);
               time_seg=zeros(njt,1);
            samples_seg=zeros(njt,1);
                    ddf=zeros(njt,1);
            
            for i=1:njt
                num_seg(i)=2^(i-1);
                time_seg(i)=dur/num_seg(i);
                samples_seg(i)=floor(n/num_seg(i));
                ddf(i)=1/time_seg(i);
            end    
            
            [~,nv]=min(abs( ddf-1));
            
            df=ddf(nv);
            
            NW=num_seg(nv);
            mmm=samples_seg(nv);
            
            % fprintf('\n NW=%d  statdof=%d  df=%8.4g Hz  \n\n',NW,NW*2,df);
            
            %%%%
            
            dB_ref=20*log10(std(amp)/app.ref);
            
            [fl,fc,fu,~]=one_third_octave_frequencies();    
                
            %%%  begin overlap
            %
                mH=floor((mmm/2)-1);
            %
                full=zeros(mH,1);
            %
                nov=0;
            %
                for ijk=1:(2*NW-1)
            %
                    amp_seg=zeros(mmm,1);
            %
                    try 
                        amp_seg(1:mmm)=amp((1+ nov):(mmm+ nov));
                    catch
                        fprintf(' ijk=%d  NW=%d  mmm=%d  nov=%d  length(amp)=%d \n',ijk,NW,mmm,nov,length(amp));
                        warndlg(' amp_seg error ');
                        return;
                    end
            %
                    nov=nov+fix(mmm/2);
            %
                    [mag_seg]=FFT_core(amp_seg,mmm,mH,app.mr,app.window);
            %
                    mag_seg=fix_size(mag_seg);
            %
                    full=full+mag_seg(1:mH);
                end
            %
                den=(2*NW-1);
                fmax=(mH-1)*df;
                freq=linspace(0,fmax,mH);
                full=full/den;  % average power spectrum
                
                full_rms=full.^(1/2);
                
                freq=fix_size(freq);
                full_rms=fix_size(full_rms);    
            %
                [~,band_dB]=convert_FFT_to_one_third_rms(freq,fl,fu,full_rms,app.ref);
                
                imax=length(band_dB);
                %
                k=1;
                for i=1:imax
                    if(band_dB(i)>0.0001 && fc(i)>1)
                        pf(k)=fc(i);
                        pspl(k)=band_dB(i);
                %
                        k=k+1;
                    end
                end
                %
                [app.oaspl]=oaspl_function(pspl);
                
            %%%

                delta=dB_ref-app.oaspl;
                
            %    fprintf('\n delta=%7.3g dB\n\n',delta);
                
                pspl=pspl+delta;
                
                [app.oaspl]=oaspl_function(pspl);
                
            %    fprintf(' oaspl = %8.4g  dB  ref: 20 micro Pa\n',oaspl);
            %
                pf=fix_size(pf);
                pspl=fix_size(pspl);
                app.spl=[pf pspl];
                            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            signal(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: ProcessingListBox
        function ProcessingListBoxValueChanged(app, event)
            signal(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            ClosePlots(app);
        
            fprintf('\n * * * * * * * * \n\n');
            
            fig_num=1;
            
            FS = app.InputArrayNameEditField.Value;
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end                
            
            iamp=app.InputAmplitudeListBox.Value;
            
            %%%%%%%%            
            
            ntime=app.ProcessingListBox.Value;
            
            if(contains(ntime,'Entire'))
                app.t1=app.THM(1,1);
                app.t2=app.THM(end,1);
            else    
                app.t1=app.StartTimesecEditField;
                app.t2=app.EndTimesecEditField;   
            end
            
            y=detrend(app.THM(:,2));     
            
            if(contains(iamp,'Accel'))
                tim=app.THM(:,1);
                dt=mean(diff(tim));
                y=y*386;
                y=dt*cumtrapz(y);
                y=detrend(y);
            end         
            
            app.THM(:,2)=y;        
            
            %%%%%%%%
            
            app.ref=1e-06;  % 1 micro in/sec
            
            spl_function_alt_no(app);
            
            if(app.iflag==999)
                return;
            end
            

            pf=app.spl(:,1);
            pspl=app.spl(:,2);
            

            %
            
            iwn=1;  % no weighting
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %       
                fprintf('\n Overall = %8.4g dB  ref 20 micro in/sec \n\n',app.oaspl);
            %
                if(iwn==1)
                    p_rms = app.ref*(10^(app.oaspl/20.));
                    fprintf('  Overall Velocity = %8.4g in/sec rms  \n\n',p_rms);
                end
            %
                figure(fig_num);
                fig_num=fig_num+1;
                plot(pf,pspl);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %
                out7=sprintf('One-Third Octave Velocity Spectrum   Overall=%7.4g dB \n Ref= 20 micro in/sec',app.oaspl); 
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
                
                fmin=app.MinEditField.Value;
                fmax=app.MaxEditField.Value;
                xlim([fmin,fmax]);
                
                [xtt,xTT,iflag]=xtick_label(fmin,fmax);
            
                if(iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    fmin=min(xtt);
                    fmax=max(xtt);
                    xlim([fmin,fmax]);    
                end   
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
            %
              pf=fix_size(pf);
              pspl=fix_size(pspl);
              SPL=[pf pspl];
            %%
                a(1)=1;
                a(2)=2;
                a(3)=4;
                a(4)=8; 
                a(5)=16;
                a(6)=31.5;
                a(7)=63;
                a(8)=125;
                a(9)=250;
                a(10)=500;
                a(11)=1000;
                a(12)=2000;
                a(13)=4000;
                a(14)=8000;
                a(15)=16000;
            %
                clear f;
            %
                bn=zeros(length(pspl),1);
                
                for i=1:length(pspl)
                    bn(i)=i;
                    f{i}=' ';
                    
                    for j=1:15
                            
                        if( (abs(pf(i)-a(j))/a(j)) < 0.05 )
                                            
                            if(j==11)
                                f{i}='1K';
                                break;
                            end
                            if(j==12)
                                f{i}='2K';
                                break;
                            end
                            if(j==13)
                                f{i}='4K';
                                break;
                            end
                            if(j==14)
                                f{i}='8K';
                                break;
                            end
                            if(j==15)
                                f{i}='16K';
                                break;
                            end               
                            f{i}=sprintf('%g',a(j));
                            break;
                        end    
              
                    end    
                       
                end
            %
                f=fix_size(f);
            %
                cpf(1)=pf(1);
                oct=2^(1/3);
                for i=2:length(pf)
                    cpf(i)=cpf(i-1)*oct;
                end
            %
                figure(fig_num);
                fig_num=fig_num+1;
                xx=log10(cpf);
                width=0.7;
                bar(xx,pspl,width);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');
                set(gca,'Xtick',xx)
                set(gca,'XTickLabel',f)
            %
                minp=min(pspl);
                maxp=max(pspl);
            %
                for i=0:20
                    b=i*10;
                    if(b>minp)
                        ymin=b-10;
                        break;
                    end    
                end
            %
                for i=0:20
                    b=i*10;
                    if(b>maxp)
                        ymax=b;
                        break;
                    end    
                end
            %
                fmin=log10(min(pf)/1.2599);
                fmax=log10(max(pf)*1.2599);
            %
                xlim([fmin,fmax]);
                try
                    ylim([ymin,ymax]);
                catch
                end
            %  
 
            try
                name=app.OutputVelocitySpectrumArrayNameEditField.Value;
                assignin('base',name, SPL);                
            catch
            end            
                  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVelocitySpectrumdBUIFigure = uifigure('Visible', 'off');
            app.UIVelocitySpectrumdBUIFigure.Name = 'UI Velocity Spectrum dB';
            app.velocity_spectrum_dBver10byTomIrvineLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.velocity_spectrum_dBver10byTomIrvineLabel.Text = 'velocity_spectrum_dB     ver 1.1   by Tom Irvine       Velocity Spectrum in One-Third Octave Format';
            app.CalculateButton = uibutton(app.UIVelocitySpectrumdBUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReturnButton = uibutton(app.UIVelocitySpectrumdBUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec)  & amplitude';
            app.InputAmplitudeListBoxLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.InputAmplitudeListBoxLabel.Text = 'Input Amplitude';
            app.InputAmplitudeListBox = uilistbox(app.UIVelocitySpectrumdBUIFigure);
            app.InputAmplitudeListBox.Items = {'Acceleration (G)', 'Velocity (in/sec)'};
            app.InputAmplitudeListBox.Value = 'Acceleration (G)';
            app.InputArrayNameEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'text');
            app.ProcessingListBoxLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.ProcessingListBoxLabel.Text = 'Processing';
            app.ProcessingListBox = uilistbox(app.UIVelocitySpectrumdBUIFigure);
            app.ProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.ProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessingListBoxValueChanged, true);
            app.ProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'numeric');
            app.PlotFrequenciesHzLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'numeric');
            app.MinEditField.Value = 4;
            app.MaxEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'numeric');
            app.MaxEditField.Value = 1000;
            app.OutputVelocitySpectrumArrayNameEditFieldLabel = uilabel(app.UIVelocitySpectrumdBUIFigure);
            app.OutputVelocitySpectrumArrayNameEditFieldLabel.Text = 'Output Velocity Spectrum Array Name';
            app.OutputVelocitySpectrumArrayNameEditField = uieditfield(app.UIVelocitySpectrumdBUIFigure, 'text');
            % Show the figure after all components are created
            app.UIVelocitySpectrumdBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = velocity_spectrum_dB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIVelocitySpectrumdBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVelocitySpectrumdBUIFigure)
        end
    end
end