classdef vibrationdata_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vibrationdata_AppUIFigure       matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        CheckforVersionUpdateButton     matlab.ui.control.Button
        SDOFResponsetoBaseInputAppliedForceButton  matlab.ui.control.Button
        CalculateFFTButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        ClearApplicationDataButton      matlab.ui.control.Button
        CloseAllPlotsButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SignalAnalysisTab               matlab.ui.container.Tab
        AnalyzeSignal2Button            matlab.ui.control.Button
        SelectSignalAnalysis2ListBox    matlab.ui.control.ListBox
        SelectSignalAnalysis2ListBoxLabel  matlab.ui.control.Label
        AnalyzeSignal1Button            matlab.ui.control.Button
        SelectSignalAnalysis1ListBox    matlab.ui.control.ListBox
        SelectSignalAnalysis1ListBoxLabel  matlab.ui.control.Label
        SelectAmplitudeTypeListBox      matlab.ui.control.ListBox
        SelectAmplitudeTypeListBoxLabel  matlab.ui.control.Label
        SelectInputDomainListBox        matlab.ui.control.ListBox
        SelectInputDomainListBoxLabel   matlab.ui.control.Label
        GenerateTab                     matlab.ui.container.Tab
        GenerateTabGroup                matlab.ui.container.TabGroup
        SignalIndexTab                  matlab.ui.container.Tab
        PerformAnalysisButton           matlab.ui.control.Button
        SelectSignalLabel               matlab.ui.control.Label
        GenerateListBox                 matlab.ui.control.ListBox
        aTab                            matlab.ui.container.Tab
        OutputNameEditField             matlab.ui.control.EditField
        OutputNameEditFieldLabel        matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        YaxisLabelEditField             matlab.ui.control.EditField
        YaxisLabelEditFieldLabel        matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        TabGroup_3                      matlab.ui.container.TabGroup
        SineTab                         matlab.ui.container.Tab
        CalculateButton_sine            matlab.ui.control.Button
        RectifyListBox                  matlab.ui.control.ListBox
        RectifyListBoxLabel             matlab.ui.control.Label
        PhaseAngledegEditField          matlab.ui.control.EditField
        PhaseAngledegEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        MultipleSineTonesTab            matlab.ui.container.Tab
        CalculateButton_multi           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NumberofTonesListBox            matlab.ui.control.ListBox
        NumberofTonesListBoxLabel       matlab.ui.control.Label
        CosineTab                       matlab.ui.container.Tab
        FrequencyHzEditField_2          matlab.ui.control.EditField
        FrequencyHzEditField_2Label     matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.EditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        CalculateButton_cosine          matlab.ui.control.Button
        DampedSineTab                   matlab.ui.container.Tab
        AmplitudeEditField_dsine        matlab.ui.control.EditField
        DelayTimeEditFieldLabel_2       matlab.ui.control.Label
        DelayTimeEditField              matlab.ui.control.EditField
        DelayTimeEditFieldLabel         matlab.ui.control.Label
        CalculateButton_damped_sine     matlab.ui.control.Button
        DampingRatio1EditField          matlab.ui.control.EditField
        DampingRatio1EditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField_dsine      matlab.ui.control.EditField
        FrequencyHzEditField_3Label     matlab.ui.control.Label
        bTab_2                          matlab.ui.container.Tab
        TabGroup_4                      matlab.ui.container.TabGroup
        SineSweepTab                    matlab.ui.container.Tab
        NumberofOctavesEditField        matlab.ui.control.NumericEditField
        NumberofOctavesEditFieldLabel   matlab.ui.control.Label
        CalculateSpectrumFunctionListBox  matlab.ui.control.ListBox
        CalculateSpectrumFunctionLabel  matlab.ui.control.Label
        UITable_sweep                   matlab.ui.control.Table
        NumberofCoordinatesListBox      matlab.ui.control.ListBox
        NumberofCoordinatesLabel        matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        TimeOptionListBox               matlab.ui.control.ListBox
        TimeOptionListBoxLabel          matlab.ui.control.Label
        DurationsecEditField_sweep      matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel_sweep  matlab.ui.control.Label
        SampleRateHzEditField_sweep     matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel_sweep  matlab.ui.control.Label
        SweepDirectionListBox           matlab.ui.control.ListBox
        SweepDirectionListBoxLabel      matlab.ui.control.Label
        SweepTypeListBox                matlab.ui.control.ListBox
        SweepTypeListBoxLabel           matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        SDOFResponseBaseExcitationTab   matlab.ui.container.Tab
        WindowCyclesEditField           matlab.ui.control.EditField
        WindowCyclesEditFieldLabel      matlab.ui.control.Label
        TransmissibilityArrayEditField  matlab.ui.control.EditField
        TransmissibilityArrayEditFieldLabel  matlab.ui.control.Label
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        CalculateListBox                matlab.ui.control.ListBox
        CalculateListBoxLabel           matlab.ui.control.Label
        RelDispResponseArrayEditField   matlab.ui.control.EditField
        RelDispResponseArrayEditFieldLabel  matlab.ui.control.Label
        AccelResponseArrayEditField     matlab.ui.control.EditField
        AccelResponseArrayEditFieldLabel  matlab.ui.control.Label
        BaseExcitationArrayEditField    matlab.ui.control.EditField
        BaseExcitationArrayEditFieldLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ResultsTextArea_sweep           matlab.ui.control.TextArea
        ResultsTextAreaLabel_sweep      matlab.ui.control.Label
        CalculateButton_base            matlab.ui.control.Button
        SDOFResponseAppliedForceTab     matlab.ui.container.Tab
        WindowCyclesEditField_2         matlab.ui.control.EditField
        WindowCyclesEditField_2Label    matlab.ui.control.Label
        MasslbmEditField                matlab.ui.control.EditField
        MasslbmEditFieldLabel           matlab.ui.control.Label
        UnitsListBox_force              matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        VelResponseArrayEditField       matlab.ui.control.EditField
        VelResponseArrayEditFieldLabel  matlab.ui.control.Label
        AmplificationFactorQEditField_force  matlab.ui.control.EditField
        AmplificationFactorQEditField_2Label  matlab.ui.control.Label
        NaturalFrequencyHzEditField_force  matlab.ui.control.EditField
        NaturalFrequencyHzEditField_2Label  matlab.ui.control.Label
        CalculateListBox_force          matlab.ui.control.ListBox
        CalculateListBox_2Label         matlab.ui.control.Label
        DispResponseArrayEditField      matlab.ui.control.EditField
        DispResponseArrayEditFieldLabel  matlab.ui.control.Label
        AccelResponseArrayEditField_force  matlab.ui.control.EditField
        AccelResponseArrayEditField_2Label  matlab.ui.control.Label
        AppliedForceArrayEditField      matlab.ui.control.EditField
        AppliedForceArrayEditFieldLabel  matlab.ui.control.Label
        ResultsTextArea_sweep_force     matlab.ui.control.TextArea
        ResultsTextAreaLabel_sweep_force  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_ForceArray                matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        CalculateButton_force           matlab.ui.control.Button
        cTab                            matlab.ui.container.Tab
        YaxisLabelEditField_white_2     matlab.ui.control.EditField
        YaxisLabelEditField_2Label_3    matlab.ui.control.Label
        HighpassFreqHzEditField         matlab.ui.control.EditField
        HighpassFreqHzEditFieldLabel    matlab.ui.control.Label
        LowpassFreqHzEditField          matlab.ui.control.EditField
        LowpassFreqHzEditFieldLabel     matlab.ui.control.Label
        BandlimitviaFilteringListBox    matlab.ui.control.ListBox
        BandlimitviaFilteringListBoxLabel  matlab.ui.control.Label
        ResultsTextArea_white           matlab.ui.control.TextArea
        ResultsTextAreaLabel_white      matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        WhiteBasicTab                   matlab.ui.container.Tab
        OutputNameEditField_white       matlab.ui.control.EditField
        OutputNameEditField_2Label      matlab.ui.control.Label
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        SampleRateHzEditField_white     matlab.ui.control.EditField
        SampleRateHzEditField_2Label    matlab.ui.control.Label
        DurationsecEditField_white      matlab.ui.control.EditField
        DurationsecEditField_2Label     matlab.ui.control.Label
        StdDevEditField                 matlab.ui.control.EditField
        StdDevEditFieldLabel            matlab.ui.control.Label
        CalculateButton_white_noise_basic  matlab.ui.control.Button
        WhiteCorrectedTab               matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        RandomNumberSeedListBox_cor     matlab.ui.control.ListBox
        RandomNumberSeedListBox_3Label_2  matlab.ui.control.Label
        T3_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2  matlab.ui.control.Label
        T2_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor  matlab.ui.control.Label
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        StdDevEditField_wc              matlab.ui.control.EditField
        StdDevEditField_2Label_2        matlab.ui.control.Label
        DurationsecEditField_wc         matlab.ui.control.EditField
        DurationsecEditField_5Label_3   matlab.ui.control.Label
        SampleRateHzEditField_wc        matlab.ui.control.EditField
        SampleRateHzEditField_4Label    matlab.ui.control.Label
        DurationTaperPercentEditField_cor  matlab.ui.control.EditField
        DurationTaperPercentEditFieldLabel_cor  matlab.ui.control.Label
        CalculateButton_white_noise_corrected  matlab.ui.control.Button
        SaveTab                         matlab.ui.container.Tab
        OutputNameEditField_cor         matlab.ui.control.EditField
        OutputNameEditFieldLabel_cor    matlab.ui.control.Label
        ExportFunctionListBox_cor       matlab.ui.control.ListBox
        ExportFunctionListBoxLabel_cor  matlab.ui.control.Label
        SaveButton_cor                  matlab.ui.control.Button
        TwoCorrelatedWhiteSignalsTab    matlab.ui.container.Tab
        StdDev2EditField                matlab.ui.control.EditField
        StdDev2EditFieldLabel           matlab.ui.control.Label
        StdDev1EditField                matlab.ui.control.EditField
        StdDev1EditFieldLabel           matlab.ui.control.Label
        SampleRateHzEditField_two_white  matlab.ui.control.EditField
        SampleRateHzEditField_2Label_2  matlab.ui.control.Label
        DurationsecEditField_two_white  matlab.ui.control.EditField
        DurationsecEditField_5Label     matlab.ui.control.Label
        CorrelationCoefficientEditField  matlab.ui.control.EditField
        CorrelationCoefficientEditFieldLabel  matlab.ui.control.Label
        OutputNameEditField_two_white   matlab.ui.control.EditField
        OutputNameEditField_2Label_6    matlab.ui.control.Label
        CalculateButton_two_correlated_white  matlab.ui.control.Button
        AddWhitetoSignalTab             matlab.ui.container.Tab
        SelectInputArrayListBox_add_white  matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        listbox_method                  matlab.ui.control.ListBox
        text1                           matlab.ui.control.Label
        StdDevEditField_add_white       matlab.ui.control.EditField
        StdDevEditField_2Label          matlab.ui.control.Label
        OutputNameEditField_add_white   matlab.ui.control.EditField
        OutputNameEditField_2Label_5    matlab.ui.control.Label
        CalculateButton_add_white_noise  matlab.ui.control.Button
        dTab                            matlab.ui.container.Tab
        SampleRateEditField_sk          matlab.ui.control.EditField
        DurationsecEditField_2Label_4   matlab.ui.control.Label
        YaxisLabelEditField_sk          matlab.ui.control.EditField
        YaxisLabelEditField_2Label_2    matlab.ui.control.Label
        StdDevEditField_sk              matlab.ui.control.EditField
        DurationsecEditField_2Label_3   matlab.ui.control.Label
        DurationsecEditField_sk         matlab.ui.control.EditField
        DurationsecEditField_2Label_2   matlab.ui.control.Label
        ResultsTextArea_random_sk       matlab.ui.control.TextArea
        ResultsTextAreaLabel_random_sk  matlab.ui.control.Label
        TabGroup7                       matlab.ui.container.TabGroup
        RandomwithSkewnessKurtosisTab   matlab.ui.container.Tab
        SkewnessListBox                 matlab.ui.control.ListBox
        SkewnessListBoxLabel            matlab.ui.control.Label
        RandomNumberSeedListBox_2       matlab.ui.control.ListBox
        RandomNumberSeedListBox_2Label  matlab.ui.control.Label
        KurtosisEditField               matlab.ui.control.EditField
        KurtosisEditFieldLabel          matlab.ui.control.Label
        BandlimitviaLowpassFilterListBox_Sarkani_sk  matlab.ui.control.ListBox
        BandlimitviaLowpassFilterListBoxLabel_2  matlab.ui.control.Label
        Filteratsamplerate10Label_2     matlab.ui.control.Label
        OutputNameEditField_sk          matlab.ui.control.EditField
        OutputNameEditField_2Label_3    matlab.ui.control.Label
        CalculateButton_sk              matlab.ui.control.Button
        SarkaniRandomTab                matlab.ui.container.Tab
        BandlimitviaLowpassFilterListBox_Sarkani  matlab.ui.control.ListBox
        BandlimitviaLowpassFilterListBoxLabel  matlab.ui.control.Label
        kurtosisEditField               matlab.ui.control.EditField
        kurtosisEditFieldLabel          matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        nEditFieldLabel                 matlab.ui.control.Label
        betaEditField                   matlab.ui.control.EditField
        betaEditFieldLabel              matlab.ui.control.Label
        SpecificationMethodListBox      matlab.ui.control.ListBox
        SpecificationMethodListBoxLabel  matlab.ui.control.Label
        Filteratsamplerate10Label       matlab.ui.control.Label
        OutputNameEditField_Sarkani     matlab.ui.control.EditField
        OutputNameEditField_2Label_4    matlab.ui.control.Label
        CalculateButton_Sarkani         matlab.ui.control.Button
        PinkNoiseTab                    matlab.ui.container.Tab
        CalculateButton_pink_2          matlab.ui.control.Button
        AudioOutputNameEditField        matlab.ui.control.EditField
        OutputNameEditField_2Label_7    matlab.ui.control.Label
        LapaceTransformListBox          matlab.ui.control.ListBox
        LapaceTransformListBoxLabel     matlab.ui.control.Label
        PinknoiseovertheaudiofrequencydomainLabel  matlab.ui.control.Label
        OutputNameEditField_pink        matlab.ui.control.EditField
        OutputNameEditField_2Label_2    matlab.ui.control.Label
        CalculateButton_pink            matlab.ui.control.Button
        eTab                            matlab.ui.container.Tab
        OutputArrayNameEditField_halfsine  matlab.ui.control.EditField
        OutputArrayNameEditField_2Label_2  matlab.ui.control.Label
        PostPulseDurationsecEditField   matlab.ui.control.EditField
        PostPulseDurationsecEditFieldLabel  matlab.ui.control.Label
        PulseDurationsecEditField       matlab.ui.control.EditField
        PulseDurationsecEditFieldLabel  matlab.ui.control.Label
        PrepulseDurationsecEditField    matlab.ui.control.EditField
        PrepulseDurationsecEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField_halfsine  matlab.ui.control.EditField
        SampleRateHzEditField_3Label_2  matlab.ui.control.Label
        YaxisLabelEditField_halfsine    matlab.ui.control.EditField
        YaxisLabelEditField_4Label_2    matlab.ui.control.Label
        AmplitudeEditField_halfsine     matlab.ui.control.EditField
        AmplitudeEditField_3Label       matlab.ui.control.Label
        CalculateButton_halfsine        matlab.ui.control.Button
        PulseTypeListBox                matlab.ui.control.ListBox
        PulseTypeListBoxLabel           matlab.ui.control.Label
        fTab                            matlab.ui.container.Tab
        NumberofHalfSinesListBox        matlab.ui.control.ListBox
        NumberofHalfSinesLabel          matlab.ui.control.Label
        OutputArrayNameEditField_wavelet  matlab.ui.control.EditField
        OutputArrayNameEditField_3Label  matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.EditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        WaveletLabel                    matlab.ui.control.Label
        CalculateButton_wavelet         matlab.ui.control.Button
        YaxisLabelEditField_wavelet     matlab.ui.control.EditField
        YaxisLabelEditField_4Label_3    matlab.ui.control.Label
        SampleRateHzEditField_wavelet   matlab.ui.control.EditField
        SampleRateHzEditField_3Label_3  matlab.ui.control.Label
        DelayTimesecEditField           matlab.ui.control.EditField
        DelayTimesecEditFieldLabel      matlab.ui.control.Label
        AmplitudeEditField_wavelet      matlab.ui.control.EditField
        AmplitudeEditField_3Label_2     matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        gTab                            matlab.ui.container.Tab
        CalculateButton_IEEE            matlab.ui.control.Button
        SelectUnitListBox               matlab.ui.control.ListBox
        SelectUnitListBoxLabel          matlab.ui.control.Label
        PreshockDurationsecEditField    matlab.ui.control.EditField
        PreshockDurationsecEditFieldLabel  matlab.ui.control.Label
        NumberofBeatsEditField          matlab.ui.control.EditField
        NumberofBeatsEditFieldLabel     matlab.ui.control.Label
        NumberofCyclesEditField         matlab.ui.control.EditField
        NumberofCyclesEditFieldLabel    matlab.ui.control.Label
        PausesecEditField               matlab.ui.control.EditField
        PausesecEditFieldLabel          matlab.ui.control.Label
        FrequencyHzEditField_IEEE       matlab.ui.control.EditField
        FrequencyHzEditField_3Label_2   matlab.ui.control.Label
        AccelerationGEditField          matlab.ui.control.EditField
        AccelerationGEditFieldLabel     matlab.ui.control.Label
        AccelerationListBox             matlab.ui.control.ListBox
        AccelerationListBoxLabel        matlab.ui.control.Label
        SuggestextrahalfcycleforzeronetdisplacementLabel  matlab.ui.control.Label
        IEEEstd693SineBeatLabel         matlab.ui.control.Label
        hTab                            matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Enterkurtosisrealnumber3EditField  matlab.ui.control.EditField
        Enterkurtosisrealnumber3EditFieldLabel  matlab.ui.control.Label
        MethodListBox                   matlab.ui.control.ListBox
        MethodListBoxLabel              matlab.ui.control.Label
        AmplitudeLabelEditField         matlab.ui.control.EditField
        AmplitudeLabelEditFieldLabel    matlab.ui.control.Label
        Enterdofrealnumber4EditField    matlab.ui.control.EditField
        Enterdofrealnumber4Label        matlab.ui.control.Label
        SampleRateHzEditField_student   matlab.ui.control.EditField
        SampleRateHzEditField_4Label_2  matlab.ui.control.Label
        DurationsecEditField_student    matlab.ui.control.EditField
        DurationsecEditField_5Label_4   matlab.ui.control.Label
        StdDevEditField_student         matlab.ui.control.EditField
        StdDevEditField_3Label          matlab.ui.control.Label
        ResultsTextArea_student         matlab.ui.control.TextArea
        ResultsTextAreaLabel_student    matlab.ui.control.Label
        OutputArrayNameEditField_student  matlab.ui.control.EditField
        OutputArrayNameEditField_3Label_2  matlab.ui.control.Label
        CalculateButton_student         matlab.ui.control.Button
        StudentstdistributionLabel      matlab.ui.control.Label
        iTab                            matlab.ui.container.Tab
        HighPassFreqHzEditField         matlab.ui.control.EditField
        HighPassFreqHzEditFieldLabel    matlab.ui.control.Label
        SelectUnitListBox_add_sine      matlab.ui.control.ListBox
        SelectUnitListBox_2Label        matlab.ui.control.Label
        YSEditField_add_Sine            matlab.ui.control.EditField
        OutputArrayNameEditField_3Label_4  matlab.ui.control.Label
        UITable_add_sine                matlab.ui.control.Table
        ResultsTextArea_add_sine        matlab.ui.control.TextArea
        ResultsTextAreaLabel_add_sine   matlab.ui.control.Label
        OutputArrayNameEditField_add_sine  matlab.ui.control.EditField
        OutputArrayNameEditField_3Label_3  matlab.ui.control.Label
        NumberofSinesListBox            matlab.ui.control.ListBox
        NumberofSinesListBoxLabel       matlab.ui.control.Label
        AmplitudeTypeListBox            matlab.ui.control.ListBox
        AmplitudeTypeLabel              matlab.ui.control.Label
        VelocityDisplacementCorrectionListBox  matlab.ui.control.ListBox
        VelocityDisplacementCorrectionLabel  matlab.ui.control.Label
        SelectInputArrayListBox_add_sine  matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel_2  matlab.ui.control.Label
        UpdateListButton_add_sine       matlab.ui.control.Button
        listbox_method_add_sine         matlab.ui.control.ListBox
        text1_2                         matlab.ui.control.Label
        CalculateButton_add_sine        matlab.ui.control.Button
        AddsinetonestoexistingsignalLabel  matlab.ui.control.Label
        jTab                            matlab.ui.container.Tab
        GotoHALTHASSButton              matlab.ui.control.Button
        HALTHASSsimulationLabel         matlab.ui.control.Label
        kTab                            matlab.ui.container.Tab
        OutputArrayNameEditField_chirp  matlab.ui.control.EditField
        OutputArrayNameEditField_5Label  matlab.ui.control.Label
        SweepTypeListBox_chirp          matlab.ui.control.ListBox
        SweepTypeListBox_2Label         matlab.ui.control.Label
        AmplitudeEditField_chirp        matlab.ui.control.EditField
        AmplitudeEditField_3Label_3     matlab.ui.control.Label
        YaxisLabelEditField_chirp       matlab.ui.control.EditField
        YaxisLabelEditField_4Label_4    matlab.ui.control.Label
        SampleRateHzEditField_chirp     matlab.ui.control.EditField
        SampleRateHzEditField_4Label_3  matlab.ui.control.Label
        EndFreqHzEditField              matlab.ui.control.EditField
        EndFreqHzEditFieldLabel         matlab.ui.control.Label
        PhaseradEditField               matlab.ui.control.EditField
        PhaseradEditFieldLabel          matlab.ui.control.Label
        DurationsecEditField_chirp      matlab.ui.control.EditField
        DurationsecEditField_5Label_5   matlab.ui.control.Label
        StartFreqHzEditField            matlab.ui.control.EditField
        StartFreqHzEditFieldLabel       matlab.ui.control.Label
        CalculateButton_chirp           matlab.ui.control.Button
        ChirpLabel                      matlab.ui.control.Label
        lTab                            matlab.ui.container.Tab
        YaxisLabelEditField_unit_impulse  matlab.ui.control.EditField
        YaxisLabelEditField_4Label      matlab.ui.control.Label
        UnitImpulseFunctionLabel        matlab.ui.control.Label
        OutputArrayNameEditField_unit_impulse  matlab.ui.control.EditField
        OutputArrayNameEditField_2Label  matlab.ui.control.Label
        SampleRateHzEditField_unit_impulse  matlab.ui.control.EditField
        SampleRateHzEditField_3Label    matlab.ui.control.Label
        DurationsecEditField_unit_impulse  matlab.ui.control.EditField
        DurationsecEditField_5Label_2   matlab.ui.control.Label
        CalculateButton_unit_impulse    matlab.ui.control.Button
        mTab                            matlab.ui.container.Tab
        GotoSinemultiplecolumnsButton   matlab.ui.control.Button
        SinemultiplecolumnsLabel        matlab.ui.control.Label
        nTab                            matlab.ui.container.Tab
        SweepParametersLabel            matlab.ui.control.Label
        UITable_msweep                  matlab.ui.control.Table
        CalculateButton_msweep          matlab.ui.control.Button
        CalculateButton_load_msweep     matlab.ui.control.Button
        SaveModelNameEditField          matlab.ui.control.EditField
        SaveModelNameEditFieldLabel     matlab.ui.control.Label
        OutputTimeHistoryNameEditField  matlab.ui.control.EditField
        OutputTimeHistoryNameEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField_msweep    matlab.ui.control.EditField
        SampleRateHzEditField_5Label    matlab.ui.control.Label
        DurationsecEditField_msweep     matlab.ui.control.EditField
        DurationsecEditField_6Label     matlab.ui.control.Label
        RowListBox                      matlab.ui.control.ListBox
        RowListBoxLabel                 matlab.ui.control.Label
        RateListBox                     matlab.ui.control.ListBox
        RateListBoxLabel                matlab.ui.control.Label
        DeleteRowBelowButton            matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        NumberofSweepSetsListBox        matlab.ui.control.ListBox
        NumberofSweepSetsLabel          matlab.ui.control.Label
        CalculateButton_multiple_sine   matlab.ui.control.Button
        MultipleSineSweepsLabel         matlab.ui.control.Label
        oTab                            matlab.ui.container.Tab
        RandomNumberSeedListBox_ihs     matlab.ui.control.ListBox
        RandomNumberSeedListBox_3Label  matlab.ui.control.Label
        MinEditField_ihs                matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxEditField_ihs                matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        HalfSinePulseDurationmsecLabel  matlab.ui.control.Label
        YaxisLabelEditField_ihs         matlab.ui.control.EditField
        YaxisLabelEditField_5Label      matlab.ui.control.Label
        SampleRateHzEditField_ihs       matlab.ui.control.EditField
        SampleRateHzEditField_4Label_4  matlab.ui.control.Label
        TimebetweenPulsessecLabel       matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        OutputArrayPrefixEditField      matlab.ui.control.EditField
        OutputArrayPrefixEditFieldLabel  matlab.ui.control.Label
        PrePulseDurationsecEditField    matlab.ui.control.EditField
        PrePulseDurationsecEditFieldLabel  matlab.ui.control.Label
        NominalAmplitudeEditField       matlab.ui.control.EditField
        NominalAmplitudeEditFieldLabel  matlab.ui.control.Label
        NumberofPulsesEditField         matlab.ui.control.EditField
        NumberofPulsesEditFieldLabel    matlab.ui.control.Label
        CalculateButton_half_sine_series  matlab.ui.control.Button
        GenerateseriesofintermittenthalfsinepulsesLabel  matlab.ui.control.Label
        StructuralDynamicsTab           matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        SpringMassSystemsTab            matlab.ui.container.Tab
        AnalyzeButton                   matlab.ui.control.Button
        SelectSystemListBox             matlab.ui.control.ListBox
        SelectSystemListBoxLabel        matlab.ui.control.Label
        SelectNumberofDOFListBox        matlab.ui.control.ListBox
        SelectNumberofDOFListBoxLabel   matlab.ui.control.Label
        StructuresSystemsTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        BeamsTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BeamClassicalTab                matlab.ui.container.Tab
        SelectBeamClassicalListBox      matlab.ui.control.ListBox
        SelectBeamClassicalListBoxLabel  matlab.ui.control.Label
        AnalyzeButtonStructures_BeamClassical  matlab.ui.control.Button
        BeamFEATab                      matlab.ui.container.Tab
        SelectBeamFEAListBox            matlab.ui.control.ListBox
        SelectBeamFEAListBoxLabel       matlab.ui.control.Label
        AnalyzeButtonStructures_BeamFEA  matlab.ui.control.Button
        PlatesTab                       matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        RectangularPlateBendingTab      matlab.ui.container.Tab
        AnalyzeButtonStructures_RectangularPlateBending  matlab.ui.control.Button
        SelectRectangularPlateListBox   matlab.ui.control.ListBox
        SelectRectangularPlateLabel     matlab.ui.control.Label
        CircularPlateBendingTab         matlab.ui.container.Tab
        AnalyzeButtonStructures_CircularPlate  matlab.ui.control.Button
        SelectPlateTypeListBox          matlab.ui.control.ListBox
        SelectPlateTypeListBoxLabel     matlab.ui.control.Label
        PlateInPlaneTab                 matlab.ui.container.Tab
        AnalyzeButtonStructures_PlateInPlane  matlab.ui.control.Button
        SelectPlateListBox              matlab.ui.control.ListBox
        SelectPlateListBoxLabel         matlab.ui.control.Label
        RingsCylindricalShellsTab       matlab.ui.container.Tab
        AnalyzeButtonStructures_CylindricalShell  matlab.ui.control.Button
        SelectStructureListBox          matlab.ui.control.ListBox
        SelectStructureListBoxLabel     matlab.ui.control.Label
        RodsShaftsTab                   matlab.ui.container.Tab
        AnalyzeButtonStructures_Rod     matlab.ui.control.Button
        SelectRodAnalysisListBox        matlab.ui.control.ListBox
        SelectRodAnalysisListBoxLabel   matlab.ui.control.Label
        MiscellaneousTab_Structures     matlab.ui.container.Tab
        AnalyzeButtonStructures_Misc    matlab.ui.control.Button
        SelectMiscellaneousListBox      matlab.ui.control.ListBox
        SelectMiscellaneousListBoxLabel  matlab.ui.control.Label
        EigenvalueProblemsTab           matlab.ui.container.Tab
        SelectAnalysisEigenListBox      matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_2    matlab.ui.control.Label
        AnalyzeButtonEigen              matlab.ui.control.Button
        NastranToolboxTab               matlab.ui.container.Tab
        AnalyzeButtonNastran            matlab.ui.control.Button
        SelectAnalysisNastranListBox    matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_3    matlab.ui.control.Label
        TransferFunctionsTab            matlab.ui.container.Tab
        SelectAnalysisTransListBox      matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_4    matlab.ui.control.Label
        AnalyzeButtonTrans              matlab.ui.control.Button
        MiscellaneousTab                matlab.ui.container.Tab
        AnalyzeButtonMisc               matlab.ui.control.Button
        SelectAnalysisMiscListBox       matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_5    matlab.ui.control.Label
        AcousticsTab                    matlab.ui.container.Tab
        AnalyzeAcousticsButton          matlab.ui.control.Button
        SelectAcousticAnalysisListBox   matlab.ui.control.ListBox
        SelectAcousticAnalysisListBoxLabel  matlab.ui.control.Label
        MainListBox                     matlab.ui.control.ListBox
        MainListBoxLabel                matlab.ui.control.Label
        ToolboxesMiscTab                matlab.ui.container.Tab
        MiscIIAnalyzeButton             matlab.ui.control.Button
        MiscIAnalyzeButton              matlab.ui.control.Button
        ToolboxAnalyzeButton            matlab.ui.control.Button
        MiscellaneousIIListBox          matlab.ui.control.ListBox
        MiscellaneousIIListBoxLabel     matlab.ui.control.Label
        MiscellaneousIListBox           matlab.ui.control.ListBox
        MiscellaneousIListBoxLabel      matlab.ui.control.Label
        ToolboxesListBox                matlab.ui.control.ListBox
        ToolboxesListBoxLabel           matlab.ui.control.Label
        FatigueFDSTab                   matlab.ui.container.Tab
        AnalyzeFatigueButton            matlab.ui.control.Button
        FatigueAuxListBox               matlab.ui.control.ListBox
        FatigueAuxListBoxLabel          matlab.ui.control.Label
        SelectFatigueAnalysisListBox    matlab.ui.control.ListBox
        SelectFatigueAnalysisListBoxLabel  matlab.ui.control.Label
        ShockTab                        matlab.ui.container.Tab
        BeginAnalysisIButton            matlab.ui.control.Button
        BeginAnalysisIIButton           matlab.ui.control.Button
        SelectAnalysisIIListBox         matlab.ui.control.ListBox
        SelectAnalysisIIListBoxLabel    matlab.ui.control.Label
        SelectAnalysisIListBox          matlab.ui.control.ListBox
        SelectAnalysisIListBoxLabel     matlab.ui.control.Label
        ImportDataTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        LibraryTab                      matlab.ui.container.Tab
        SelectTypeListBox               matlab.ui.control.ListBox
        SelectTypeListBoxLabel          matlab.ui.control.Label
        SelectArrayListBox              matlab.ui.control.ListBox
        SelectArrayListBoxLabel         matlab.ui.control.Label
        ReadDataButton                  matlab.ui.control.Button
        TextorExcelTab                  matlab.ui.container.Tab
        importdatamethodLabel           matlab.ui.control.Label
        ReadFileMethod2Button           matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        ReadFileMethod1Button           matlab.ui.control.Button
        matTab                          matlab.ui.container.Tab
        ReadmatButton                   matlab.ui.control.Button
        ManualDataEntryTab              matlab.ui.container.Tab
        OutputArrayNameEditField_Manual  matlab.ui.control.EditField
        OutputArrayNameEditField_5Label_2  matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        NumberofRowsListBox             matlab.ui.control.ListBox
        NumberofRowsListBoxLabel        matlab.ui.control.Label
        NumberofColumnsListBox          matlab.ui.control.ListBox
        NumberofColumnsListBoxLabel     matlab.ui.control.Label
        UITableManual                   matlab.ui.control.Table
        PlotTab                         matlab.ui.container.Tab
        SelectPlotTypeIListBox          matlab.ui.control.ListBox
        SelectPlotTypeIListBoxLabel     matlab.ui.control.Label
        SelectPlotTypeIIListBox         matlab.ui.control.ListBox
        SelectPlotTypeIIListBoxLabel    matlab.ui.control.Label
        PlotIIButton                    matlab.ui.control.Button
        PlotIButton                     matlab.ui.control.Button
        LVTab                           matlab.ui.container.Tab
        LVAnalyzeButton                 matlab.ui.control.Button
        LVAuxListBox                    matlab.ui.control.ListBox
        LVAuxListBoxLabel               matlab.ui.control.Label
        SelectLVAnalysisListBox         matlab.ui.control.ListBox
        SelectLVAnalysisListBoxLabel    matlab.ui.control.Label
        WorkspaceTab                    matlab.ui.container.Tab
        WorkspaceListBox                matlab.ui.control.ListBox
        WorkspaceListBoxLabel           matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        fff        %
        iaco       % 
        ifat       %
        ins        %
        indomain   %
        iamplitude %
        isignal    %
        iu_force   %
        ndof       %
        nf         %
        nMethod    %
        ntype      %
        num        %
        signal     %
        THM        %
        nIEEE      %
        nStudent   %
        n_add_sine %
        n_msweep   %
        accel      %
        vel        %
        dispx      %
        afilename  %
    end
    
    methods (Access = private)

        function units_force(app)

            Items = strtrim(app.UnitsListBox_force.Items);
            im = strtrim(app.UnitsListBox_force.Value);
            app.iu_force = find(strcmp(Items, im));

            if(app.iu_force==1)
                app.MasslbmEditFieldLabel.Text='Mass (lbm)';
                app.Label_ForceArray.Text='The Force Array must have two columns:  time (sec) & force (lbf)';
            else
                app.MasslbmEditFieldLabel.Text='Mass (kg)';
                app.Label_ForceArray.Text='The Force Array must have two columns:  time (sec) & force (N)';            
            end
        end    

        function update_list(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    cell_array{i} = vars(i).name;
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.WorkspaceListBox.Items=unique(sort(cell_array)); 
            % app.SelectInputArrayListBox.Items=app.WorkspaceListBox.Items;
        end    


        function msweep(app)
            app.n_msweep = str2num(app.NumberofSweepSetsListBox.Value);  

            Nrows=app.n_msweep;
            Ncolumns=4;
             
            A=app.UITable_msweep.Data;
             
            sz=size(A);
            Arows=sz(1);
             
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}='';
                end
                data_s{i,1}=sprintf('%d',i);
            end    
             
            if(~isempty(A))
                
                M=min([ Arows Nrows ]);
                
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}=A{i,j};
                    end    
                end   
             
            end
             
            app.UITable_msweep.Data=data_s;

        end    

        function ChangeManualData(app)
            nrow=str2num(app.NumberofRowsListBox.Value);
            ncol=str2num(app.NumberofColumnsListBox.Value);

            q=zeros(nrow,ncol);

            app.UITableManual.Data=q;
        end    

        function student_method(app)
             
            clear(app);

            Items = char(app.MethodListBox.Items);
            im=app.MethodListBox.Value;
            [app.nStudent]=find_index(Items,im);

            app.Enterdofrealnumber4EditField.Visible='off';
            app.Enterdofrealnumber4Label.Visible='off';

            app.Enterkurtosisrealnumber3EditField.Visible='off';
            app.Enterkurtosisrealnumber3EditFieldLabel.Visible='off';

            if(app.nStudent==1)
                app.Enterdofrealnumber4EditField.Visible='on';
                app.Enterdofrealnumber4Label.Visible='on';  
            else
                app.Enterkurtosisrealnumber3EditField.Visible='on';
                app.Enterkurtosisrealnumber3EditFieldLabel.Visible='on';
            end
                    
        end    

        function IEEE(app)
            clear(app);
            
            Items = char(app.AccelerationListBox.Items);
            im=app.AccelerationListBox.Value;
            [app.nIEEE]=find_index(Items,im);

            app.AccelerationGEditField.Visible='off';
            app.AccelerationGEditFieldLabel.Visible='off';
            
            if(app.nIEEE==5)
                app.AccelerationGEditField.Visible='on';
                app.AccelerationGEditFieldLabel.Visible='on';    
            end

        end    

        function update_add_white(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox_add_white.Items=unique(sort(cell_array)); 
        end   

        function update_add_sine(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox_add_sine.Items=unique(sort(cell_array)); 
        end            

        function Sarkani(app)

            Items = char(app.SpecificationMethodListBox.Items);
            im=app.SpecificationMethodListBox.Value;
            [nmeth]=find_index(Items,im);

            app.nEditField.Visible='off';
            app.nEditFieldLabel.Visible='off';
            app.betaEditField.Visible='off';
            app.betaEditFieldLabel.Visible='off';
            app.kurtosisEditField.Visible='off';
            app.kurtosisEditFieldLabel.Visible='off';

            if(nmeth==1)
                app.kurtosisEditField.Visible='on';
                app.kurtosisEditFieldLabel.Visible='on';    
            else
                app.nEditField.Visible='on';
                app.nEditFieldLabel.Visible='on';
                app.betaEditField.Visible='on';
                app.betaEditFieldLabel.Visible='on';
            end
        end    

        function ChangeFilter(app)
            Items = char(app.BandlimitviaFilteringListBox.Items);
            im=app.BandlimitviaFilteringListBox.Value;
            [app.nf]=find_index(Items,im);          

            app.HighpassFreqHzEditField.Visible='off';
            app.HighpassFreqHzEditFieldLabel.Visible='off';

            if(app.nf==1 || app.nf==2)
                app.LowpassFreqHzEditField.Visible='on';
                app.LowpassFreqHzEditFieldLabel.Visible='on';
            end     

            if(app.nf==1)                
                app.LowpassFreqHzEditFieldLabel.Text='Low-pass Freq (Hz)';
            end
            if(app.nf==2)
                app.LowpassFreqHzEditFieldLabel.Text='Low-pass Freq (Hz)';
                app.HighpassFreqHzEditField.Visible='on';
                app.HighpassFreqHzEditFieldLabel.Visible='on';            
            end
            if(app.nf==3)
                app.LowpassFreqHzEditField.Visible='off';
                app.LowpassFreqHzEditFieldLabel.Visible='off';
            end    
        end       

        function ChangeNumberCoordinates(app)
            ns=app.NumberofCoordinatesListBox.Value;
            app.num=str2num(ns); 
            
            q=zeros(app.num,2);
            
            app.UITable_sweep.Data=q;
            
        end

        function ChangeTimeOption(app)
            n=app.SweepTypeListBox.Value;
            app.DurationsecEditFieldLabel_sweep.Text='Duration (sec)';
                
            if(strcmp(n,'linear'))
                app.TimeOptionListBox.Visible='off';
                app.TimeOptionListBoxLabel.Visible='off';
                app.ntype=1;
            else
                app.TimeOptionListBox.Visible='on';
                app.TimeOptionListBoxLabel.Visible='on';
                m=app.TimeOptionListBox.Value;
                if(contains(m,'Sweep'))
                    app.DurationsecEditFieldLabel_sweep.Text='Sweep Rate (oct/min)';    
                end
                app.ntype=2;
            end
        end       

        function GenerateChange(app)

            Items = char(app.GenerateListBox.Items);
            im=app.GenerateListBox.Value;
            [n]=find_index(Items,im); 

            app.GenerateTabGroup.SelectedTab=app.GenerateTabGroup.Children(n);
        end    

        function LB_change(app)
            clear(app); 

            N=str2num(app.NumberofTonesListBox.Value);
           
            for i = 1:N
               for j=1:2
                  data_s{i,j} = '';     
               end  
               data_s{i,3} = '0';   
            end
 
            app.UITable.Data=data_s;          
        end

        function spring_mass(app)
            Items = char(app.SelectNumberofDOFListBox.Items);
            im=app.SelectNumberofDOFListBox.Value;
            [app.ndof]=find_index(Items,im);

            if(app.ndof==1)
                string_w{1}=sprintf('Natural Frequency & Response to Initial Conditions');
                string_w{2}=sprintf('Steady-State Sine Force or Acceleration Input');
                string_w{3}=sprintf('Classical Shock Base Input');
                string_w{4}=sprintf('Base Input Time Domain');
                string_w{5}=sprintf('Classical Shock Applied Force');
                string_w{6}=sprintf('Applied Force Time Domain');
                string_w{7}=sprintf('PSD Base Input');
                string_w{8}=sprintf('Peak Sigma for Random Base Input');
                string_w{9}=sprintf('Transmissibility, Base Excitation or Applied Force');   
                string_w{10}=sprintf('Spring Surge'); 
                string_w{11}=sprintf('Accidental Drop Shock');
                string_w{12}=sprintf('SDOF Response to force PSD, VRS & FDS');
            end
            if(app.ndof==2)
                string_w{1}=sprintf('System Natural Frequencies & Base Input Response');
                string_w{2}=sprintf('System Applied Force');   
                string_w{3}=sprintf('System with Dashpots');
                string_w{4}=sprintf('Three Parameter Isolation System');  
                string_w{5}=sprintf('Automobile');                    
            end
            if(app.ndof==3)
                string_w{1}=sprintf('System Natural Frequencies');
                string_w{2}=sprintf('System Base Excitation');
                string_w{3}=sprintf('ESA Simple Spacecraft Model');
                string_w{4}=sprintf('ESA Simple Launcher/Spacecraft Model');
                string_w{5}=sprintf('Semidefinite System, Applied Force');                
            end
            if(app.ndof==4)
                string_w{1}=sprintf('Six-DOF Mass with Four Isolators');
                string_w{2}=sprintf('Six-DOF Mass with Arbitrary Number of Isolators'); 
            end
            if(app.ndof==5)
                   
                string_w{1}=sprintf('Applied Arbitary Force, Single Point, Newmark-Beta');
                string_w{2}=sprintf('Applied Arbitary Force, Multiple Points, Newmark-Beta');
                string_w{3}=sprintf('Applied Arbitary Force, Recursive Filtering');                

                string_w{4}=sprintf('Enforced Acceleration or Displacement, Newmark-Beta');
                string_w{5}=sprintf('Acceleration & Force, Newmark-Beta');
                string_w{6}=sprintf('FEA Preprocessor');
            end            
            
            app.SelectSystemListBox.Items=string_w;
            
        end    

        function clear(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  

            app.ResultsTextArea_add_sine.Visible='off';
            app.ResultsTextAreaLabel_add_sine.Visible='off';          
            app.ResultsTextArea_student.Visible='off';
            app.ResultsTextAreaLabel_student.Visible='off';
            app.ResultsTextAreaLabel_random_sk.Visible='off';
            app.ResultsTextArea_random_sk.Visible='off';           
            % app.SDOFResponsetoBaseInputAppliedForceButton.Visible='off';
            app.OutputNameEditFieldLabel.Visible='off';
            app.OutputNameEditField.Visible='off';
            app.SaveButton.Visible='off';  
            % app.CalculateFFTButton.Visible='off';     
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';   
            app.ResultsTextArea_white.Visible='off';
            app.ResultsTextAreaLabel_white.Visible='off';     
            app.NumberofOctavesEditField.Visible='off';
            app.NumberofOctavesEditFieldLabel.Visible='off';              
        end    

        function NG(app)
         
        end    

        function time_history_options(app)

            ClearAux(app);

            m=app.iamplitude;
            p=app.isignal;    

            if(m==1) % Acceleration
               pp=1; 
               if(p==pp) % Statistics
                  signal_editing_utilities_a;           
               end; pp=pp+1;    
               if(p==pp) % Integrate & differentiate
                  integrate_differentiate_a;            
               end; pp=pp+1;         
               if(p==pp) % Fourier Transform
                  vibrationdata_fourier_transform_ah;              
               end; pp=pp+1;                
               if(p==pp) % Waterfall FFT & Spectrogram
                   waterfall_fft_ah;           
               end; pp=pp+1;    
               if(p==pp) % Power Spectral Density
                    app.isignal=5;
                    psd_aux(app);
               end; pp=pp+1;      
               if(p==pp) % Power Spectral Density Envelope
                    psd_env_aux(app);            
               end; pp=pp+1;         
               if(p==pp) % SDOF Response to Base Input
                   sdof_response_time_domain;      
               end; pp=pp+1;  
               if(p==pp) % SDOF Response to Base Input, Reverse
                   handles.s= vibrationdata_sdof_base_reverse;      
               end; pp=pp+1;     
               if(p==pp) % Shock Response Spectrum
                    clear ss;
                    app.AnalyzeSignal2Button.Visible='on';
                    app.SelectSignalAnalysis2ListBox.Visible='on';
                    app.SelectSignalAnalysis2ListBoxLabel.Visible='on';
                    ss{1}='SRS';
                    ss{2}='SRS, Triaxial Hypersphere';
                    ss{3}='Waterfall SRS';
                    ss{4}='SRS Spec from Time History';
                    ss{5}='Temporal Moments';
                    % ss{6}='Batch SRS & Enveloping';
                    app.SelectSignalAnalysis2ListBox.Items=ss;            
               end; pp=pp+1;            
               if(p==pp) % Shock Saturation Removal
                    clear ss;
                    app.AnalyzeSignal2Button.Visible='on';
                    app.SelectSignalAnalysis2ListBox.Visible='on';
                    app.SelectSignalAnalysis2ListBoxLabel.Visible='on';
                    ss{1}='Butterworth Filter';
                    ss{2}='Bessel Filter';
                    ss{3}='Mean Filter';
                    app.SelectSignalAnalysis2ListBox.Items=ss;                            
               end; pp=pp+1;        
               if(p==pp) % Rainflow Cycle Counting
                   rainflow_app;         
               end; pp=pp+1;    
               if(p==pp) % Fatigue Damage Spectrum
                   FDS_App;         
               end; pp=pp+1;           
               if(p==pp) % Auto & cross-correlation
                   signal_correlation_a        
               end; pp=pp+1;         
               if(p==pp) % Filters
                   filter_App;              
               end; pp=pp+1;   
               if(p==pp) % Cepstrum
                   handles.s= vibrationdata_cepstrum;                   
               end; pp=pp+1;        
               if(p==pp) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end; pp=pp+1;         
               if(p==pp) % Wavelet Reconstruction
                   handles.s= vibrationdata_wavelet_reconstruction_main;                  
               end; pp=pp+1;  
               if(p==pp) % Energy Response Spectrum
                   handles.s= vibrationdata_energy_base;                  
               end; pp=pp+1;  
               if(p==pp) % ISO Main
                   iso_main;         
               end; pp=pp+1;   
               if(p==pp) % Lomb_Scargle Periodgram
                   handles.s= Lomb_Scargle_Periodogram;         
               end; pp=pp+1;   
               if(p==pp) % Hilbert transform
                   handles.s= vibrationdata_Hilbert;         
               end; pp=pp+1;    
               if(p==pp) % Time history fatigue severity
                   handles.s=vibrationdata_time_history_fatigue_severity;         
               end; pp=pp+1;      
               if(p==pp) % Time history fatigue severity
                   handles.s=vibrationdata_time_history_fatigue_severity_alt;         
               end; pp=pp+1;                
               if(p==pp) % Batch I
                   handles.s= vibrationdata_batch_acceleration;         
               end; pp=pp+1;      
               if(p==pp) % Batch II
                   handles.s= vibrationdata_batch_acceleration_II;         
               end; pp=pp+1;            
               try
                    set(handles.s,'Visible','on');
               catch
               end
            end
            if(m==2) % Velocity
               if(p==1) % Statistics
                  signal_editing_utilities_a;        
               end       
               if(p==2) % Integrate or differentiate
                 integrate_differentiate_a;            
               end    
               if(p==3) % Fourier Transform
                  vibrationdata_fourier_transform_ah;              
               end          
               if(p==4) % Waterfall FFT & Spectrogram
                   waterfall_fft_ah;             
               end     
               if(p==5) % Power Spectral Density
                   app.isignal=5;
                   psd_aux(app);             
               end         
               if(p==6) % Rainflow Cycle Counting
                   rainflow_app;           
               end   
               if(p==7) % Auto & cross-correlation
                   signal_correlation_a        
               end           
               if(p==8) % ISO Generic Vibration Criteria
                   handles.s= vibrationdata_iso_generic;            
               end  
               if(p==9) % Filters
                   filter_App;                   
               end        
               if(p==10) % Cepstrum
                   handles.s= vibrationdata_cepstrum;            
               end    
               if(p==11) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end        
               if(p==12) % Batch
                   handles.s= vibrationdata_batch;         
               end          
               if(p==13) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end     
               try
                  set(handles.s,'Visible','on');
               catch
               end
            end
            if(m==3) % Displacement
               if(p==1) % Statistics
                  signal_editing_utilities_a;           
               end            
               if(p==2) % Integrate or Differentiate
                  integrate_differentiate_a;         
               end          
               if(p==3) % Fourier Transform
                  vibrationdata_fourier_transform_ah;           
               end        
               if(p==4) % Waterfall FFT & Spectrogram
                   waterfall_fft_ah;            
               end           
               if(p==5) % Power Spectral Density
                   app.isignal=5;
                   psd_aux(app);           
               end         
               if(p==6) % Rainflow Cycle Counting
                   rainflow_app;          
               end   
               if(p==7) % Auto & cross-correlation
                   signal_correlation_a        
               end      
               if(p==8) % Filters
                   filter_App;           
               end          
               if(p==9) % Cepstrum
                   handles.s= vibrationdata_cepstrum;            
               end     
               if(p==10) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end     
               if(p==11) % Batch
                   handles.s= vibrationdata_batch;         
               end          
               if(p==12) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end       
               try
                    set(handles.s,'Visible','on');
               catch
               end
            end
            if(m==4) % Force
               if(p==1) % Statistics
                  signal_editing_utilities_a;           
               end          
               if(p==2) % Fourier Transform
                   vibrationdata_fourier_transform_ah;         
               end            
               if(p==3) % Waterfall FFT & Spectrogram
                   waterfall_fft_ah;       
               end           
               if(p==4) % Power Spectral Density
                   app.isignal=5;
                   psd_aux(app);           
               end    
               if(p==5) % SDOF Response to Applied Force
                   sdof_response_time_domain;   
               end    
               if(p==6) % Shock Response Spectrum
                   handles.s= vibrationdata_srs_force;        
               end  
               if(p==7) % Rainflow Cycle Counting
                  rainflow_app;      
               end   
               if(p==8) % Fatigue Damage Spectrum
                  handles.s= vibrationdata_force_FDS;      
               end         
               if(p==9) % Auto & cross-correlation
                   signal_correlation_a        
               end      
               if(p==10) % Filters
                   filter_App;                
               end    
               if(p==11) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end    
               if(p==12) % Batch
                   handles.s= vibrationdata_batch;         
               end     
               if(p==13) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end   
               try
                   set(handles.s,'Visible','on');
               catch
               end
            end
            if(m==5) % Pressure
               if(p==1) % Statistics
                  signal_editing_utilities_a;              
               end              
               if(p==2) % Fourier Transform
                  vibrationdata_fourier_transform_ah;          
               end         
               if(p==3) % Waterfall FFT & Spectrogram
                  waterfall_fft_ah;            
               end        
               if(p==4) % Power Spectral Density
                  app.isignal=5;
                  psd_aux(app);           
               end     
               if(p==5) % Sound Pressure Level
                   vibrationdata_spl_a;                
               end                     
               if(p==6) % Auto & cross-correlation
                   signal_correlation_a        
               end           
               if(p==7) % Filters
                  filter_App;                
               end   
               if(p==8) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end     
               if(p==9)
                   smc_s_016_psd_spl_mpe;
               end    
               if(p==10) % Batch
                   handles.s= vibrationdata_batch;         
               end 
               if(p==11) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end
               try
                   set(handles.s,'Visible','on');
               catch
               end
            end
            if(m==6) % Stress
               if(p==1) % Statistics
                  signal_editing_utilities_a;       
               end            
               if(p==2) % Fourier Transform
                  vibrationdata_fourier_transform_ah;              
               end   
               if(p==3) % Waterfall FFT & Spectrogram
                  waterfall_fft_ah;            
               end     
               if(p==4) % Power Spectral Density
                  app.isignal=5;  
                  psd_aux(app);            
               end      
               if(p==5) % Auto & cross-correlation
                   signal_correlation_a        
               end      
               if(p==6) % Rainflow Cycle Counting
                  rainflow_app;     
               end
               if(p==7) % Filters
                   filter_App;                  
               end          
               if(p==8) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end
               if(p==9) % Principal, von Mises, Tresca
                   handles.s= vibrationdata_equivalent_uniaxial;         
               end                 
               if(p==10) % Batch
                   handles.s= vibrationdata_batch;         
               end     
               if(p==11) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end
               try
                  set(handles.s,'Visible','on');
               catch
               end
            end     
            if(m==7) % Strain
               if(p==1) % Statistics
                   signal_editing_utilities_a;          
               end 
               if(p==2) % Fourier Transform
                   vibrationdata_fourier_transform_ah;            
               end    
               if(p==3) % Waterfall FFT & Spectrogram
                   waterfall_fft_ah;       
               end      
               if(p==4) % Power Spectral Density
                   app.isignal=5;
                   psd_aux(app);          
               end          
               if(p==5) % Auto & cross-correlation
                   signal_correlation_a        
               end           
               if(p==6) % Rainflow Cycle Counting
                   rainflow_app;         
               end     
               if(p==7) % Filters
                   filter_App;                
               end       
               if(p==8) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end     
               if(p==9) % Batch
                   handles.s= vibrationdata_batch;         
               end     
               if(p==10) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end
               try
                   set(handles.s,'Visible','on'); 
               catch
               end
            end
            if(m==8) % Other
               if(p==1) % Statistics
                  signal_editing_utilities_a;        
               end   
               if(p==2) % Integrate or differentiate
                  integrate_differentiate_a;    
               end   
               if(p==3) % Fourier Transform
                  vibrationdata_fourier_transform_ah;              
               end      
               if(p==4) % Waterfall FFT & Spectrogram
                  waterfall_fft_ah;              
               end      
               if(p==5) % Power Spectral Density
                  app.isignal=5; 
                  psd_aux(app);            
               end       
               if(p==6) % Auto & cross-correlation
                   signal_correlation_a        
               end      
               if(p==7) % Rainflow Cycle Counting
                  rainflow_app;         
               end     
               if(p==8) % Filters
                   filter_App;                  
               end  
               if(p==9) % Cepstrum
                   handles.s= vibrationdata_cepstrum;                   
               end 
               if(p==10) % Sine & Damped Sine Curve-fit
                   sine_curve_fit_ah;                 
               end        
               if(p==11) % Batch
                   handles.s= vibrationdata_batch;         
               end     
               if(p==12) % Batch II
                   handles.s= vibrationdata_batch_II;         
               end
               try
                   set(handles.s,'Visible','on');
               catch
               end            
            end
        end
        function psd_options(app)
            m=app.iamplitude;
            p=app.isignal;

            if(m==1) % acceleration
                if(p==1) % overall RMS
                       setappdata(0,'psd_type',1);           
                       psd_plot_App;     
                end
                if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;           
                end      
                if(p==3) % SDOF response 
                       vrs_fds_base;
                end       
                if(p==4) % SDOF Response to Base Input, Reverse
                       handles.s=vibrationdata_psd_sdof_base_reverse;      
                end                   
                if(p==5) % PSD synthesis
                       vibrationdata_psd_synth_ah;    
                end  
                if(p==6) % PSD synthesis, two PSDs
                       handles.s= vibrationdata_PSD_accel_synth_two;    
                end        
                if(p==7) % Power Transmissibility from two PSDs
                       handles.s= vibrationdata_power_transmissibility;    
                end      
                if(p==8) % Response PSD from Input PSD & Power Trans
                       handles.s= vibrationdata_input_trans_mult;    
                end          
                if(p==9) % Envelope PSD via VRS
                       envelope_psd_a;    
                end     
                if(p==10) % psd_time_scaling or band splitting
                       psd_tools_a    
                end         
                if(p==11) % add margin
                       dboct_a;    
                end      
                if(p==12) % maximum envelope
                       handles.s=maximum_envelope;    
                end 
                if(p==13) % maximum & mean envelopes individual arrays
                       handles.s=psd_max_interp;    
                end      
                if(p==14) %   Isolator + Min Workmanship PSD
                       handles.s=isolator_plus_min_workmanship_PSD;    
                end       
                if(p==15) %   Response PSD + Min Workmanship PSD
                       handles.s=response_PSD_plus_min_workmanship_PSD;    
                end    
                if(p==16) % vibrationdata_psd_fatigue_severity
                       stress_from_acceleration_psd;    
                end          
                if(p==17) % vibrationdata_psd_fatigue_severity
                       handles.s=vibrationdata_psd_fatigue_severity;    
                end   
                if(p==18) % relative_displacement_two_psds
                       handles.s=relative_displacement_two_psds;    
                end
                if(p==19) % Martin_Marietta_psd_distance_joints
                       handles.s=Martin_Marietta_psd_distance_joints;    
                end       
                if(p==20) % batch processing
                       handles.s=vibrationdata_batch_psd;    
                end 
                try
                       set(handles.s,'Visible','on');
                catch
                end
            end
            if(m==2) % velocity
                   if(p==1) % overall RMS
                       setappdata(0,'psd_type',2);          
                       psd_plot_App;  
                   end   
                   if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;       
                   end   
                   if(p==3) % synthesize from white noise
                       handles.s= vibrationdata_PSD_vel_synth;       
                   end        
                   try
                      set(handles.s,'Visible','on');
                   catch
                   end
            end
            if(m==3) % displacement
                   if(p==1) % overall RMS
                        setappdata(0,'psd_type',3);          
                       psd_plot_App;      
                   end  
                   if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;        
                   end  
                   if(p==3) % time history synthesis
                       vibrationdata_psd_synth_ah;             
                   end
                   try
                       set(handles.s,'Visible','on');
                   catch
                   end    
            end
            if(m==4) % force
                   if(p==1) % overall RMS
                       setappdata(0,'psd_type',4);           
                       psd_plot_App;     
                   end    
                   if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;             
                   end      
                   if(p==3) % time history synthesis from white noise
                       vibrationdata_psd_synth_ah;               
                   end          
                   if(p==4) % time history synthesis from sine
                       handles.s=vibrationdata_psd_syn_fp_sine;               
                   end            
                   if(p==5) % power transmissibility
                       handles.s= vibrationdata_power_transmissibility;             
                   end        
                   if(p==6) % Response PSD from Input PSD & Power Trans
                       handles.s= vibrationdata_input_trans_mult;    
                   end                 
                   if(p==7) % SDOF Response to Force PSD
                       vrs_fds_force;             
                   end   
                   try
                        set(handles.s,'Visible','on');
                   catch
                   end    
            end
            if(m==5) % pressure
                   if(p==1) % overall RMS
                       setappdata(0,'psd_type',5);           
                       psd_plot_App;        
                   end     
                   if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;         
                   end 
                   if(p==3) % time history synthesis main
                       vibrationdata_psd_synth_ah;             
                   end                
                   if(p==4) % power transmissibility
                       handles.s= vibrationdata_power_transmissibility;             
                   end    
                   if(p==5) % Response PSD from Input PSD & Power Trans
                       handles.s= vibrationdata_input_trans_mult;    
                   end     
                   if(p==6) % convert pressure psd to acoustic spl
                       handles.s=vibrationdata_psd_spl;    
                   end 
                   if(p==7) % Convert Octave to Narrowband Format
                       handles.s=convert_octave_narrowband;             
                   end
                   if(p==8) % vibration response spectrum
                       handles.s=vibrationdata_VRS_pressure;             
                   end
                   if(p==9) % SDOF Response
                       handles.s=vrs_fds_pressure;             
                   end       
                   if(p==10) % PSD Envelope via VRS
                       envelope_psd_a;    
                   end
                   if(p==11) % maximum envelope
                       handles.s=maximum_envelope;    
                   end
                   try
                        set(handles.s,'Visible','on');       
                   catch
                   end    
            end
            if(m==6) % stress
                   if(p==1) % overall RMS
                       setappdata(0,'psd_type',6);
                       psd_plot_App;        
                   end     
                   if(p==2) % octave format
                       handles.s= vibrationdata_psd_oct;         
                   end 
                   if(p==3) % time history synthesis
                       vibrationdata_psd_synth_ah;             
                   end    
                   if(p==4) % time history synthesis - white noise, kurtosis
                       handles.s=vibrationdata_psd_syn_wnb_kurtosis;            
                   end          
                   if(p==5) % power transmissibility
                       handles.s= vibrationdata_power_transmissibility;             
                   end   
                   if(p==6) % Response PSD from Input PSD & Power Trans
                       handles.s= vibrationdata_input_trans_mult;    
                   end                 
                   if(p==7) % stress PSD fatigue damage
                       stress_psd_fatigue_Basquin;           
                   end
                   try
                        set(handles.s,'Visible','on');
                   catch
                   end    
            end
        end    

        function spl_options(app)            
            p=app.isignal;

            if(p==1) % overall RMS
                   handles.s= oaspl_a;                  
            end     
            if(p==2) % Convert True Full Octave SPL to One-Third
                   handles.s= spl_full_to_onethird;         
            end     
            if(p==3) % Convert True Narrowband Octave SPL to One-Third
                   handles.s=narrowband_to_onethird_SPL;         
            end      
            if(p==4) % Convert Digitized One-Third to True One-Third
                   handles.s= digitized_onethird_to_true_onethird;         
            end      
            if(p==5) % Convert Digitized_Narrowband SPL to One-Third
                   handles.s= digitized_narrowband_to_onethird_SPL;         
            end     
            if(p==6) % PNL
                   handles.s= pnl;         
            end       
            if(p==7) % EPNL
                   handles.s= epnl;         
            end        
            if(p==8) % maximum envelope
                   handles.s=maximum_envelope;    
            end  
            if(p==9) % VRS SPL
                   transmitted_pressure_a;    
            end      
            if(p==10) % acoustic seismic coupling
                   acoustic_seismic_coupling_a;    
            end     
            
            try
                set(handles.s,'Visible','on');      
            catch
            end

        end
        function srs_options(app)
            p=app.isignal;    

            if(p==1) % wavelet synth
                   wavelet_synth_a;                  
            end  
            if(p==2) % damped sine synth
                   handles.s= vibrationdata_damped_sine_synth;                  
            end    
            if(p==3) % earthquake synth
                   earthquake_synth_a;                  
            end
            if(p==4) % pyroshock synth
                   handles.s= pyro_srs_synth;                  
            end   
            if(p==5) % random synth
                   handles.s= vibrationdata_random_srs_synth;                  
            end 
            if(p==6) % exponential chirp
                   handles.s= exponential_chirp;                  
            end         
            if(p==7) % envelope SRS via PSD
                   handles.s= vibrationdata_envelope_srs_psd;                  
            end 
            if(p==8) % convert accel SRS to PV SRS
                   handles.s= vibrationdata_accel_SRS_PV_SRS;                  
            end        
            if(p==9) % convert accel SRS to FDS
                   handles.s= vibrationdata_srs_fds;                  
            end   
            if(p==10) % satisfy SRS with classical pulse
                   handles.s= srs_classical_pulse_synth;      
            end
            if(p==11) % convert SRS specification to a new Q value
                   handles.s= srs_spec_convert_Q;      
            end    
            if(p==12) % satisfy SRS with classical pulse
                   handles.s= IEC_980_Sine_Dwell;      
            end    
            if(p==13) % envelope_srs_data_a
                   handles.s= envelope_srs_data_a;      
            end  
            if(p==14) % Add margin
                   handles.s= add_margin;      
            end        
            if(p==15) % maximum envelope
                   handles.s=maximum_envelope;    
            end
            if(p==16) % SRS Batch
                   handles.s=srs_batch_processing;    
            end     
          
            try
                set(handles.s,'Visible','on');      
            catch
            end    
        end
        function FT_options(app)
            m=app.iamplitude;
            p=app.isignal; 

            if(p==1) % Overall RMS
                    handles.s= vibrationdata_overall_Fourier_transform;  
            end 
            if(p==2) % Inverse
                    handles.s= vibrationdata_inverse_Fourier_transform;  
            end  
            if(p==3) % Convert Fourier Transform Magnitude to PSD
                    handles.s= Fourier_magnitude_psd;  
            end       
            if(m==1) % accel
                if(p==4)
                    RTV_FAS_SRS;
                end
            end    
            if(m==5) % pressure
                if(p==4) % Convert Fourier Transform Magnitude to SPL
                    handles.s=Fourier_magnitude_spl;  
                end  
                if(p==5) % Convert One-Third Octave Magnitude to PSD
                    handles.s=one_third_octave_pressure_to_psd;  
                end         
            end
            try
                set(handles.s,'Visible','on');       
            catch
            end
        end
        function psd_aux(app)
            clear ss;
            app.AnalyzeSignal2Button.Visible='on';
            app.SelectSignalAnalysis2ListBox.Visible='on';
            app.SelectSignalAnalysis2ListBoxLabel.Visible='on';
            ss{1}='Power Spectral Density';
            ss{2}='Cross Spectral Density, H1, H2';
            ss{3}='Energy Spectral Density';
            ss{4}='Power Transmissibility for Input & Response';
            ss{5}='PSD MPE per SMC-S-016, 3.27';
            app.SelectSignalAnalysis2ListBox.Items=ss;          
        end   
        function psd_env_aux(app)
            clear ss;
            app.AnalyzeSignal2Button.Visible='on';
            app.SelectSignalAnalysis2ListBox.Visible='on';
            app.SelectSignalAnalysis2ListBoxLabel.Visible='on';
            ss{1}='Energy Response Spectrum';
            ss{2}='Fatigue Damage Spectrum';
            ss{3}='Shock Response Spectrum';
            ss{4}='PSD MPE per SMC-S-016, 3.27';
            app.SelectSignalAnalysis2ListBox.Items=ss;
        end
        function WT_options(app)
            p=app.isignal; 

            if(p==1) % Construct Time History
                    handles.s= wavelet_construct_time_history;  
            end 
            if(p==2) % Distance
                    handles.s= shock_distance;  
            end 
            if(p==3) % Joint
                    handles.s= shock_joint;  
            end
            try
                set(handles.s,'Visible','on');
            catch
            end    
        end
        function fds_options(app)                
            p=app.isignal; 

            if(p==1) % maximum envelope
                handles.s=maximum_envelope;    
            end        
            if(p==2) % maximum envelope
                handles.s=FDS_time_history_synth;    
            end     
            if(p==3) % maximum envelope
                handles.s=FDS_time_history_synth_wavelets;    
            end       
            if(p==4) % maximum envelope
                psd_envelope_via_fds;     
            end     
            if(p==5) % maximum envelope
                envelope_fds_sine_a;    
            end    
            try
                set(handles.s,'Visible','on');
            catch
            end    
        end
        function vrs_options(app)
            p=app.isignal; 

            if(p==1) % maximum envelope
                handles.s=vibrationdata_envelope_vrs_psd;    
            end    
            try
                set(handles.s,'Visible','on');
            catch
            end    
        end
        function sor_options(app)                
            p=app.isignal; 

            if(p==1) %  Time History Synthesis 
                handles.s=SOR_synth;    
            end   
            if(p==2) %  SDOF Response 
                handles.s=SOR_sdof_response;    
            end     
            if(p==3) %  VRS
                handles.s=SOR_vrs;    
            end
            try
                set(handles.s,'Visible','on');
            catch
            end    
        end
        function ssweepor_options(app)
            p=app.isignal; 

            if(p==1) %  Time History Synthesis 
                handles.s=SSOR_synth;    
            end   
            if(p==2) %  SDOF Response 
                handles.s=SSOR_sdof_response;    
            end     
            if(p==3) %  VRS
                handles.s=SSOR_vrs;    
            end
            try
                set(handles.s,'Visible','on'); 
            catch
            end    
        end

        function time_history_listbox(app)
            n=app.indomain;
            
            Items = char(app.SelectAmplitudeTypeListBox.Items);
            im=app.SelectAmplitudeTypeListBox.Value;
            [m]=find_index(Items,im);

            app.iamplitude=m;
            
            if(n==1) % Time History
                if(m==1) % Acceleration
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');   
                   string_th{end+1}=sprintf('Integrate or Differentiate');  
                   string_th{end+1}=sprintf('Fourier Transform & FFT');   
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');       
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.'); 
                   string_th{end+1}=sprintf('PSD Envelope via ERS, FDS or SRS');            
                   string_th{end+1}=sprintf('SDOF Response to Base Input');   
                   string_th{end+1}=sprintf('SDOF Response to Base Input, Reverse');     
                   string_th{end+1}=sprintf('Shock Response Spectrum & Temporal Moments');   
                   string_th{end+1}=sprintf('Shock Saturation Removal');          
                   string_th{end+1}=sprintf('Rainflow Cycle Counting'); 
                   string_th{end+1}=sprintf('Fatigue Damage Spectrum');    
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient'); 
                   string_th{end+1}=sprintf('Filters, Various');      
                   string_th{end+1}=sprintf('Cepstrum & Auto Cepstrum');           
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Wavelet Reconstruction'); 
                   string_th{end+1}=sprintf('Energy Response Spectrum');    
                   string_th{end+1}=sprintf('ISO Generic VC, 2631, 10816 & Velocity Spectrum dB');               
                   string_th{end+1}=sprintf('Lomb–Scargle Periodogram for unevenly spaced data');  
                   string_th{end+1}=sprintf('Hilbert Transform');   
                   string_th{end+1}=sprintf('Base Input Fatigue Severity');
                   string_th{end+1}=sprintf('Base Input Fatigue Severity vs. Time');      
                   string_th{end+1}=sprintf('Batch Processing I');      
                   string_th{end+1}=sprintf('Batch Processing II');         
                end
                if(m==2) % Velocity
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');    
                   string_th{end+1}=sprintf('Integrate or Differentitate');  
                   string_th{end+1}=sprintf('Fourier Transform & FFT');     
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');  
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');       
                   string_th{end+1}=sprintf('Rainflow Cycle Counting'); 
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient'); 
                   string_th{end+1}=sprintf('ISO Generic Vibration Criteria');
                   string_th{end+1}=sprintf('Filters, Various');        
                   string_th{end+1}=sprintf('Cepstrum & Auto Cepstrum');
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');        
                end
                if(m==3) % Displacement
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');   
                   string_th{end+1}=sprintf('Integrate or Differentiate');       
                   string_th{end+1}=sprintf('Fourier Transform & FFT');    
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');           
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');     
                   string_th{end+1}=sprintf('Rainflow Cycle Counting'); 
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient'); 
                   string_th{end+1}=sprintf('Filters, Various');        
                   string_th{end+1}=sprintf('Cepstrum & Auto Cepstrum'); 
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');       
                end
                if(m==4) % Force
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');        
                   string_th{end+1}=sprintf('Fourier Transform & FFT');    
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');      
                   string_th{end+1}=sprintf('SDOF Response to Applied Force');   
                   string_th{end+1}=sprintf('Shock Response Spectrum');   
                   string_th{end+1}=sprintf('Rainflow Cycle Counting');
                   string_th{end+1}=sprintf('Fatigue Damage Spectrum');       
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient');        
                   string_th{end+1}=sprintf('Filters, Various');    
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');       
                end
                if(m==5) % Pressure
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');      
                   string_th{end+1}=sprintf('Fourier Transform & FFT');     
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');     
                   string_th{end+1}=sprintf('SPL, Sound Pressure Level & PNL');             
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient'); 
                   string_th{end+1}=sprintf('Filters, Various');      
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('SPL MPE SMC-S-016');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');        
                end
                if(m==6) % Stress
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');     
                   string_th{end+1}=sprintf('Fourier Transform & FFT');    
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');    
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient');  
                   string_th{end+1}=sprintf('Rainflow Cycle Counting'); 
                   string_th{end+1}=sprintf('Filters, Various');         
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Principal, von Mises & Tresca, Multiaxis Input');       
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');       
                end
                if(m==7) % Strain
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');        
                   string_th{end+1}=sprintf('Fourier Transform & FFT');   
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');    
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient');  
                   string_th{end+1}=sprintf('Rainflow Cycle Counting');
                   string_th{end+1}=sprintf('Filters, Various');         
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');       
                end
                if(m==8) % Other
                   string_th{1}=sprintf('Statistics, Time-Varying & Signal Editing Utilities');     
                   string_th{end+1}=sprintf('Integrate or Differentiate');  
                   string_th{end+1}=sprintf('Fourier Transform & FFT');     
                   string_th{end+1}=sprintf('Waterfall FFT & Spectrogram');
                   string_th{end+1}=sprintf('PSD, Spectral Densities, Transmissibility, etc.');    
                   string_th{end+1}=sprintf('Auto & Cross-correlation, Pearson Coefficient'); 
                   string_th{end+1}=sprintf('Rainflow Cycle Counting');
                   string_th{end+1}=sprintf('Filters, Various');     
                   string_th{end+1}=sprintf('Cepstrum & Auto Cepstrum'); 
                   string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                   string_th{end+1}=sprintf('Batch Processing I');
                   string_th{end+1}=sprintf('Batch Processing II');        
                end
                app.SelectSignalAnalysis1ListBox.Items=string_th;    
            end
        end    

        function signal_analysis(app)
            n=app.indomain;
            
            Items = char(app.SelectAmplitudeTypeListBox.Items);
            im=app.SelectAmplitudeTypeListBox.Value;
            [m]=find_index(Items,im);

            app.iamplitude=m;
            
            if(n==1) %
                time_history_listbox(app);
            end    

            if(n==2)  % PSD
                if(m==1) % Acceleration PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments'); 
                   string_f{end+1}=sprintf('Convert to Octave Format or Interpolate to Narrowband');  
                   string_f{end+1}=sprintf('SDOF Response to Base Input, VRS & FDS');   
                   string_f{end+1}=sprintf('SDOF Response to Base Input, Reverse');              
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise');
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise for two PSDs');
                   string_f{end+1}=sprintf('Power Transmissibility from two PSDs');  
                   string_f{end+1}=sprintf('Response PSD from Input PSD & Power Trans');         
                   string_f{end+1}=sprintf('Envelope PSD via VRS');  
                   string_f{end+1}=sprintf('PSD Specification Band Splitting & Time Scaling');
                   string_f{end+1}=sprintf('Add dB Margin');    
                   string_f{end+1}=sprintf('Maximum Envelope from Multi-column Array');
                   string_f{end+1}=sprintf('Maximum & Mean Envelopes from Individual Arrays');
                   string_f{end+1}=sprintf('Isolator + Minimum Workmanship PSD');
                   string_f{end+1}=sprintf('Response PSD + Minimum Workmanship PSD');      
                   string_f{end+1}=sprintf('Convert Acceleration Response PSD to Stress PSD');  
                   string_f{end+1}=sprintf('Fatigue Severity');  
                   string_f{end+1}=sprintf('Relative Displacement from two Acceleration PSDs');         
                   string_f{end+1}=sprintf('Martin Marietta Attenuation for PSD Propagation');       
                   string_f{end+1}=sprintf('Batch Processing');      
                   app.SelectSignalAnalysis1ListBox.Items=string_f;      
                end
                if(m==2) % Velocity PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments');  
                   string_f{end+1}=sprintf('Convert to Octave Format');  
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise');
                   app.SelectSignalAnalysis1ListBox.Items=string_f;    
                end
                if(m==3) % Displacement PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments');              
                   string_f{end+1}=sprintf('Convert to Octave Format');   
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise');        
                   app.SelectSignalAnalysis1ListBox.Items=string_f;        
                end
                if(m==4) % Force PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments');              
                   string_f{end+1}=sprintf('Convert to Octave Format');
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise');  
                   string_f{end+1}=sprintf('Time History Synthesis from Sine Series');  
                   string_f{end+1}=sprintf('Power Transmissibility from two PSDs');       
                   string_f{end+1}=sprintf('Response PSD from Input PSD & Power Trans');       
                   string_f{end+1}=sprintf('SDOF Response to force PSD, VRS & FDS');  
                   app.SelectSignalAnalysis1ListBox.Items=string_f; 
                end 
                if(m==5) % Pressure PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments');     
                   string_f{end+1}=sprintf('Convert to Octave Format');      
                   string_f{end+1}=sprintf('Time History Synthesis');      
                   string_f{end+1}=sprintf('Power Transmissibility from two PSDs');        
                   string_f{end+1}=sprintf('Response PSD from Input PSD & Power Trans'); 
                   string_f{end+1}=sprintf('Convert to Acoustic SPL');  
                   string_f{end+1}=sprintf('Convert Octave to Narrowband Format');  
                   string_f{end+1}=sprintf('Vibration Response Spectrum (VRS)');
                   string_f{end+1}=sprintf('SDOF Response to Pressure PSD, VRS & FDS');
                   string_f{end+1}=sprintf('PSD Envelope via Transmitted Pressure VRS');       
                   string_f{end+1}=sprintf('Maximum Envelope from Multi-column Array');       
                   app.SelectSignalAnalysis1ListBox.Items=string_f; 
                end      
                if(m==6) % Stress PSD
                   string_f{1}=sprintf('Overall RMS & Spectral Moments');     
                   string_f{end+1}=sprintf('Convert to Octave Format');      
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise');
                   string_f{end+1}=sprintf('Time History Synthesis from White Noise, Kurtosis');       
                   string_f{end+1}=sprintf('Power Transmissibility from two PSDs');  
                   string_f{end+1}=sprintf('Response PSD from Input PSD & Power Trans');      
                   string_f{end+1}=sprintf('Stress PSD Fatigue Damage');      
                   app.SelectSignalAnalysis1ListBox.Items=string_f; 
                end      
            end
            
            if(n==3)  % SPL
                string_f{1}=sprintf('Overall SPL, Conversion to Pressure PSD & Apply A-weights');  
                string_f{end+1}=sprintf('Convert True Full Octave SPL to One-Third');
                string_f{end+1}=sprintf('Convert True Narrowband Octave SPL to One-Third');    
                string_f{end+1}=sprintf('Convert Digitized One-Third SPL to True One-Third'); 
                string_f{end+1}=sprintf('Convert Digitized Narrowband SPL to One-Third'); 
                string_f{end+1}=sprintf('Perceived Noise Level PNL');
                string_f{end+1}=sprintf('Effective Perceived Noise Level EPNL');
                string_f{end+1}=sprintf('Maximum Envelope from Multi-column Array');    
                string_f{end+1}=sprintf('Transmitted Pressure SDOF Response & VRS'); 
                string_f{end+1}=sprintf('Acoustic Seismic Coupling');
                
                app.SelectSignalAnalysis1ListBox.Items=string_f;     
            end 
            
            if(n==4)  % SRS
                string_srs{1}=sprintf('Wavelet Synthesis'); 
                string_srs{end+1}=sprintf('Damped Sine Synthesis');  
                string_srs{end+1}=sprintf('Earthquake Synthesis'); 
                string_srs{end+1}=sprintf('Pyroshock Synthesis'); 
                string_srs{end+1}=sprintf('Random Synthesis');  
                string_srs{end+1}=sprintf('Exponential Chirp Synthesis');        
                string_srs{end+1}=sprintf('Envelope SRS via PSD, peak response');    
                string_srs{end+1}=sprintf('Convert Accel SRS to Pseudo Velocity SRS');
                string_srs{end+1}=sprintf('Convert SRS to FDS');
                string_srs{end+1}=sprintf('Satisfy SRS with Classical Pulse');
                string_srs{end+1}=sprintf('Convert SRS Spec to a New Q Value');        
                string_srs{end+1}=sprintf('IEC 980 Sine Dwell One-Third Octave');    
                string_srs{end+1}=sprintf('Envelope Measured SRS Data');  
                string_srs{end+1}=sprintf('Add dB Margin');      
                string_srs{end+1}=sprintf('Maximum Envelope from Multi-column Array');
                string_srs{end+1}=sprintf('Batch Processing');   
                   
                app.SelectSignalAnalysis1ListBox.Items=string_srs;       
            end    
            
            if(n==5)  % Fourier transform
                
                string_f{1}=sprintf('Overall level');  
                string_f{end+1}=sprintf('Inverse Fourier Transform'); 
                string_f{end+1}=sprintf('Convert Fourier Transform Magnitude to PSD'); 

                if(m==1)
                    string_f{end+1}=sprintf('RVT FAS SRS (earthquake)');
                end    
                
                if(m==5) % pressure
                    string_f{end+1}=sprintf('Convert Fourier Transform Magnitude to SPL');    
                    string_f{end+1}=sprintf('Convert One-Third Octave Magnitude to PSD');
                end
              
                app.SelectSignalAnalysis1ListBox.Items=string_f;       
            end    
            if(n==6) % Wavelet Table
                if(m==1) % Acceleration
                    string_f{1}=sprintf('Construct Time History');  
                    string_f{end+1}=sprintf('Shock Distance Attenuation');
                    string_f{end+1}=sprintf('Shock Joint Attenuation');      
                    app.SelectSignalAnalysis1ListBox.Items=string_f;             
                end
            end  
            if(n==7) % Fatigue Damage Spectrum
                if(m==1) % Generic
                    string_f{1}=sprintf('Maximum Envelope from Multi-column Array'); 
                    string_f{end+1}=sprintf('Pseudo Random Synthesize Time History for Acceleration FDS');  
                    string_f{end+1}=sprintf('Wavelet Synthesis Time History for Acceleration FDS ');
                    string_f{end+1}=sprintf('Optimized PSD via FDS');  
                    string_f{end+1}=sprintf('Optimized Sine-on-Random via FDS');       
                    app.SelectSignalAnalysis1ListBox.Items=string_f;             
                end
            end    
            if(n==8) % Vibration Response Spectrum
                if(m==1) % Acceleration
                    string_f{1}=sprintf('Synthesize PSD to Satisfy VRS'); 
                    app.SelectSignalAnalysis1ListBox.Items=string_f;             
                end
            end    
            if(n==9) % Sine-on-Random
                if(m==1) % Acceleration
                    string_f{1}=sprintf('Time History Synthesis');  
                    string_f{end+1}=sprintf('SDOF Response'); 
                    string_f{end+1}=sprintf('VRS');         
                    app.SelectSignalAnalysis1ListBox.Items=string_f;             
                end
            end 
            if(n==10) % Sine Sweep-on-Random
                if(m==1) % Acceleration
                    string_f{1}=sprintf('Time History Synthesis');  
                    string_f{end+1}=sprintf('SDOF Response'); 
                    string_f{end+1}=sprintf('VRS');                
                    app.SelectSignalAnalysis1ListBox.Items=string_f;             
                end
            end    
            
        end    
        
        function amplitude(app)
            Items = char(app.SelectInputDomainListBox.Items);
            im=app.SelectInputDomainListBox.Value;
            [app.indomain]=find_index(Items,im);

            n=app.indomain;

            if(n==1) % time history
                string_th{1}=sprintf('Acceleration');
                string_th{end+1}=sprintf('Velocity');
                string_th{end+1}=sprintf('Displacement');
                string_th{end+1}=sprintf('Force');
                string_th{end+1}=sprintf('Pressure');
                string_th{end+1}=sprintf('Stress');
                string_th{end+1}=sprintf('Strain');
                string_th{end+1}=sprintf('Other');    
            end
            if(n==2 || n==5) % psd or Fourier transform
                string_th{1}=sprintf('Acceleration');
                string_th{end+1}=sprintf('Velocity');
                string_th{end+1}=sprintf('Displacement');
                string_th{end+1}=sprintf('Force');
                string_th{end+1}=sprintf('Pressure');
                string_th{end+1}=sprintf('Stress');    
            end 
            if(n==3) % spl
                string_th{1}=sprintf('Pressure');
            end
            if(n==4) % srs
                string_th{1}=sprintf('Acceleration');
            end
            if(n==6) % wavelet table
                string_th{1}=sprintf('Acceleration');    
            end 
            if(n==7) % Fatigue Damage Spectrum
                string_th{1}=sprintf('Generic');    
            end   
            if(n==8) % Fatigue Damage Spectrum
                string_th{1}=sprintf('Acceleration');   
            end   
            if(n==9 || n==10) % Sine-on-Random or Sine Sweep-on-Random
                string_th{1}=sprintf('Acceleration');    
            end   

            app.SelectAmplitudeTypeListBox.Items=string_th;

            signal_analysis(app);
            
        end    

        function inputdomain(app)
            ClearAux(app);
            string_th{1}=sprintf('Time History');
            string_th{end+1}=sprintf('Power Spectral Density');
            string_th{end+1}=sprintf('Sound Pressure Level');
            string_th{end+1}=sprintf('Shock Response Spectrum');
            string_th{end+1}=sprintf('Fourier Transform');
            string_th{end+1}=sprintf('Wavelet Table');
            string_th{end+1}=sprintf('Fatigue Damage Spectrum');
            string_th{end+1}=sprintf('Vibration Response Spectrum');
            string_th{end+1}=sprintf('Sine-on-Random');
            string_th{end+1}=sprintf('Sine Sweep-on-Random');
            app.SelectInputDomainListBox.Items=string_th;

            amplitude(app);

        end    
        function toolbox(app)
            ClearAux(app);
            miscI(app);
            miscII(app);
            string_th{1}=sprintf('Generate Signal'); 
            string_th{end+1}=sprintf('Shock');
            string_th{end+1}=sprintf('SDOF Response:  Sine, Peak Random & Miles');
            string_th{end+1}=sprintf('Steinberg');
            string_th{end+1}=sprintf('Nastran');
            string_th{end+1}=sprintf('Honeycomb Sandwich');
            string_th{end+1}=sprintf('Derive PSD  Envelopes');
            string_th{end+1}=sprintf('dB Calculations');
            string_th{end+1}=sprintf('Stress-Velocity Relationship');    
            string_th{end+1}=sprintf('Modal Testing');     
            string_th{end+1}=sprintf('SMC-S-016 Tools');
            app.ToolboxesListBox.Items=string_th;

        end
        function miscI(app)
            ClearAux(app);
            string_th{1}=sprintf('Modal FRF');
            string_th{end+1}=sprintf('Damping Functions');
            string_th{end+1}=sprintf('Amplitude Conversion Utilities');
            string_th{end+1}=sprintf('Sine Sweep Parameters');
            string_th{end+1}=sprintf('dB Calculations');
            string_th{end+1}=sprintf('Statistical Distribution & Tolerance Factor');
            string_th{end+1}=sprintf('Wavelength');
            string_th{end+1}=sprintf('Rotation & Rotating Imbalance');
            string_th{end+1}=sprintf('Sound File Editor');
            string_th{end+1}=sprintf('Aliasing Frequencies');
            string_th{end+1}=sprintf('Wind & Waves');
            string_th{end+1}=sprintf('ESS Screening Strength');
            string_th{end+1}=sprintf('Flight & Orbital Mechanics'); 
            app.MiscellaneousIListBox.Items=string_th;
        end
        function miscII(app)
            ClearAux(app);
            string_th{1}=sprintf('Linear Algebra');
            string_th{end+1}=sprintf('Structural Static Analysis');
            string_th{end+1}=sprintf('Fracture');
            string_th{end+1}=sprintf('Traveling Wave Animation');
            string_th{end+1}=sprintf('Blast & Burst Overpressure');
            string_th{end+1}=sprintf('Fluid Systems');
            string_th{end+1}=sprintf('Panel Flutter');
            string_th{end+1}=sprintf('Unit Conversion');
            string_th{end+1}=sprintf('Time Conversion');
            string_th{end+1}=sprintf('Atmospheric Properties');
            string_th{end+1}=sprintf('Reynolds Number');
            string_th{end+1}=sprintf('Strouhal Number');
            string_th{end+1}=sprintf('Interpolate Coordinates');
            string_th{end+1}=sprintf('Interpolate Function');
            string_th{end+1}=sprintf('View Two-column Array, User-friendly format');   
            string_th{end+1}=sprintf('Digitize Plot'); 
            string_th{end+1}=sprintf('Convert Stress-Strain Curve to Energy-Strain Curve'); 
            string_th{end+1}=sprintf('Vibe Lab: Test Array'); 
            app.MiscellaneousIIListBox.Items=string_th;   
        end    

        function NS(app)

            ClearAux(app);

            Items = char(app.SelectLVAnalysisListBox.Items);
            im=app.SelectLVAnalysisListBox.Value;
            [app.ins]=find_index(Items,im);

            if(app.ins==2)
                string_th{1}=sprintf('Extract, Multiple');
                string_th{end+1}=sprintf('Extract, Multiple, Cursor');
                string_th{end+1}=sprintf('Extract & Mean Removal, Multiple');    
                string_th{end+1}=sprintf('Bessel Filter, Multiple');  
                string_th{end+1}=sprintf('SDOF Base Input Response, Multiple');    
                string_th{end+1}=sprintf('SRS, Multiple');      
                string_th{end+1}=sprintf('SRS Envelope + Simplified Envelope');    
                string_th{end+1}=sprintf('SRS Scale from Multiple Shock Events');   
                string_th{end+1}=sprintf('Max Envelope of Multiple Misc');    
                string_th{end+1}=sprintf('Read in CC Separation Source Shock');  
                string_th{end+1}=sprintf('Add dB Margin');
                string_th{end+1}=sprintf('SRS Plot');
                string_th{end+1}=sprintf('View Two-column Array, User-friendly format');  
            end
            if(app.ins==1) 
                string_th{1}=sprintf('Extract, Multiple, Alt');    
                string_th{end+1}=sprintf('Bessel Filter, Multiple');
                string_th{end+1}=sprintf('Butterworth Filter, Multiple');    
                string_th{end+1}=sprintf('SDOF Base Input Response, Multiple');
                string_th{end+1}=sprintf('FDS, Multiple, One Segment'); 
                string_th{end+1}=sprintf('FDS, Multiple, Two Segments');    
                string_th{end+1}=sprintf('FDS, Multiple, Three Segments');     
                string_th{end+1}=sprintf('FDS, Multiple, One Event');
                string_th{end+1}=sprintf('FDS, Multiple, Two Events');
                string_th{end+1}=sprintf('FDS, Multiple, Four Events');
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Single Sensor');
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Single Sensor, Alt');    
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Multiple Sensors');
                string_th{end+1}=sprintf('Optimized PSD via FDS');
                string_th{end+1}=sprintf('Response + Min Workmanship PSD');  
                string_th{end+1}=sprintf('Max Envelope of Multiple Misc'); 
                string_th{end+1}=sprintf('Read in SMC-S-016');
                string_th{end+1}=sprintf('Add dB Margin');   
                string_th{end+1}=sprintf('PSD Plot');    
                string_th{end+1}=sprintf('View Two-column Array, User-friendly format');  
                string_th{end+1}=sprintf('Time Varying Statistics, Multiple Arrays');  
            end

            app.LVAuxListBox.Items=string_th;
                    
        end    
     
        function fatigue(app)
            
            string_th{1}=sprintf('General Time History Input, Single Axis');     
            string_th{end+1}=sprintf('Stress Time History Input, Single Axis'); 
            string_th{end+1}=sprintf('Stress Time History Input, Multiaxis'); 
            string_th{end+1}=sprintf('Equivalence & Conversion Methods'); 
            string_th{end+1}=sprintf('PSD Input'); 
            string_th{end+1}=sprintf('FDS Functions & Enveloping'); 
            string_th{end+1}=sprintf('Miscellaneous');
            app.SelectFatigueAnalysisListBox.Items=string_th;

            fatigue_change(app);
        end    
        function fatigue_change(app)
            Items = char(app.SelectFatigueAnalysisListBox.Items);
            im=app.SelectFatigueAnalysisListBox.Value;
            [n]=find_index(Items,im);

            app.ifat=n;

            if(n==1)
                app.FatigueAuxListBoxLabel.Text='General Time History Input, Single Axis';
                string_th{1}=sprintf('Rainflow Cycle Counting, Single Axis');
                string_th{2}=sprintf('Fatigue Damage Spectrum for Acceleration Base Input');
                string_th{3}=sprintf('Simple Range Mean Counting');
                string_th{4}=sprintf('Range Pair Counting');
                string_th{5}=sprintf('Batch Processing');        
            end    
            if(n==2)
                app.FatigueAuxListBoxLabel.Text='Stress Time History Input, Single Axis';
                string_th{1}=sprintf('Basquin Coefficients, Mean Stress Correction');
                string_th{2}=sprintf('Nasgro, MIL-HDBK-5J or MMPDS Coefficients');
                string_th{3}=sprintf('Nasgro Long Block Input Format, Optional Endurance Knockdown');   
                string_th{4}=sprintf('Rainflow Cycle Count, Nasgro Long Block Output Format');  
                string_th{5}=sprintf('Batch Processing');    
            end                
            if(n==3)
                app.FatigueAuxListBoxLabel.Text='Stress Time History Input, Multiaxis';
                string_th{1}=sprintf('Rainflow Cycle Counting, Hypersphere only');
                string_th{2}=sprintf('Miners Damage for Multi-axis, Basquin');
                string_th{3}=sprintf('Miners Damage for Multi-axis, MIL-HDBK-5J');
            end    
            if(n==4)
                app.FatigueAuxListBoxLabel.Text='Equivalence & Conversion Methods';
                string_th{1}=sprintf('Equivalent Sine Input for Given Damage Level');
                string_th{2}=sprintf('Equivalent Narrowband Random PSD for Sine Input');
                string_th{3}=sprintf('Convert Sine-on-Random Spec to Composite PSD');
                string_th{4}=sprintf('Convert SRS to FDS');
            end    
            if(n==5)
                app.FatigueAuxListBoxLabel.Text='PSD Input';
                string_th{1}=sprintf('VRS & FDS for Base input PSD');
                string_th{2}=sprintf('Miners Damage for Stress PSD, Basquin');
                string_th{3}=sprintf('Miners Damage for Stress PSD, Nasgro, MIL-HDBK-5J, MMPDS');
                string_th{4}=sprintf('Miners Damage for Stress PSD, Dirlik, Mean Stress Correction, Basquin');
                string_th{5}=sprintf('Fatigue Life for Stress PSD, Basquin');
                string_th{6}=sprintf('Fatigue Life for Stress PSD, non-Gaussian, Basquin');
                string_th{7}=sprintf('Relative Damage for Response PSD (Dirlik Method)');
                string_th{8}=sprintf('Dirlik Method, Batch Processing');
                string_th{9}=sprintf('Accel PSD to Velox PSD to Stress PSD, Dirlik, Nasgro & MIL-HDBK-5J'); 
                string_th{10}=sprintf('Convert Sine-on-Random Spec to Composite PSD');
            end   
            if(n==6)
                string_th{1}=sprintf('FDS, Multiple, One Segment'); 
                string_th{end+1}=sprintf('FDS, Multiple, Two Segments');    
                string_th{end+1}=sprintf('FDS, Multiple, Three Segments');     
                string_th{end+1}=sprintf('FDS, Multiple, One Event');
                string_th{end+1}=sprintf('FDS, Multiple, Two Events');
                string_th{end+1}=sprintf('FDS, Multiple, Four Events');
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Single Sensor');
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Single Sensor, Alt');    
                string_th{end+1}=sprintf('Max Envelope of Multiple FDS, Multiple Sensors');
                string_th{end+1}=sprintf('Optimized PSD via FDS');
                string_th{end+1}=sprintf('Optimized Sine-on-Random via FDS');
                string_th{end+1}=sprintf('FDS Damage Summation for Multiple Events');        
                string_th{end+1}=sprintf('Plot: FDS, Multiple');   
                string_th{end+1}=sprintf('Plot: FDS, Subplots 2x2, Two Curves');
                string_th{end+1}=sprintf('Plot: FDS, Subplots 2x2, Two Curves, dB Difference'); 
            end              
            if(n==7)
                app.FatigueAuxListBoxLabel.Text='Miscellaneous';
                string_th{1}=sprintf('Miners Damage for (Stress Amplitude & Cycles), Basquin');
                string_th{2}=sprintf('Pure Sine Stress Fatigue, Basquin');            
                string_th{3}=sprintf('Shear & Normal Stress, Constant, in-phase');
                string_th{4}=sprintf('Shear & Normal Stress, Constant, out-of-phase');
                string_th{5}=sprintf('NASGRO/NASFORM parameters for an S-N Curve');
            end  
            app.FatigueAuxListBox.Items=string_th; 
        end    

        function plot(app)
            string_th{1}=sprintf('Time History'); 
            string_th{end+1}=sprintf('Two Time Histories, Scatter Plot'); 
            string_th{end+1}=sprintf('1D Array'); 
            string_th{end+1}=sprintf('PSD');
            string_th{end+1}=sprintf('PSD, Multiple');     
            string_th{end+1}=sprintf('SRS'); 
            string_th{end+1}=sprintf('SRS, Multiple'); 
            string_th{end+1}=sprintf('SRS, Two with dB Difference');   
            string_th{end+1}=sprintf('SRS, Tripartite, Multiple');
            string_th{end+1}=sprintf('FDS, Multiple');   
            string_th{end+1}=sprintf('FDS, Subplots 2x2, Two Curves');
            string_th{end+1}=sprintf('FDS, Subplots 2x2, Two Curves, dB Difference');   
            string_th{end+1}=sprintf('SPL'); 
            string_th{end+1}=sprintf('SPL, Multiple'); 
            string_th{end+1}=sprintf('Fourier Transform: magnitude & phase'); 
            string_th{end+1}=sprintf('Fourier Transform: real & imaginary'); 
            string_th{end+1}=sprintf('Nyquist & Nichols Plots'); 
            string_th{end+1}=sprintf('Two Curves & Miscellaneous'); 
            string_th{end+1}=sprintf('Multiple Curves'); 
            string_th{end+1}=sprintf('Multiple Curves Alt'); 
            string_th{end+1}=sprintf('Multiple Curves, pull down selection');
  
            app.SelectPlotTypeIListBox.Items=string_th;

            string_th2{1}=sprintf('Subplots 1x2 or 2x1'); 
            string_th2{end+1}=sprintf('Subplots 1x3 or 3x1'); 
            string_th2{end+1}=sprintf('Subplots 2x2'); 
            string_th2{end+1}=sprintf('Subplots 2x3');
            string_th2{end+1}=sprintf('Subplots 3 and 2');   
            string_th2{end+1}=sprintf('Subplots alt');    
            string_th2{end+1}=sprintf('Subplot 1x2 Two Curves Each Side');
            string_th2{end+1}=sprintf('One-Third Octave Bar Chart'); 
            string_th2{end+1}=sprintf('One-Third Octave Bar Chart, Subplots 2x1');  
            string_th2{end+1}=sprintf('One-Third Octave Bar Chart, Subplots 3x1');    
            string_th2{end+1}=sprintf('Double Y Plot'); 
            string_th2{end+1}=sprintf('Two Curves per Subplot 1x2 or 2x1');        
            string_th2{end+1}=sprintf('Three Curves per Subplot 1x3 or 3x1'); 
            string_th2{end+1}=sprintf('Sine-on-Random');   
            string_th2{end+1}=sprintf('Gnuplot Preprocessor');  

            app.SelectPlotTypeIIListBox.Items=string_th2;
        end    

        function library_read(app)

            Items = strtrim(app.SelectTypeListBox.Items);
            im = strtrim(app.SelectTypeListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1) % Acceleration Time History

                string_th{1}=sprintf('SRS 2000G Acceleration 1');
                string_th{end+1}=sprintf('SRS 2000G Acceleration 2');
                string_th{end+1}=sprintf('SRS 1000G Acceleration');  
                string_th{end+1}=sprintf('Flight Accelerometer Data, Suborbital Launch Vehicle');
                string_th{end+1}=sprintf('Solid Rocket Motor, Resonant Burn, Flight Data');
                string_th{end+1}=sprintf('Channel Beam');
                string_th{end+1}=sprintf('Drop Transient, Launch Vehicle');
                string_th{end+1}=sprintf('Space Shuttle SRB FWD IEA Slashdown');
                string_th{end+1}=sprintf('Taurus Auto');  
                string_th{end+1}=sprintf('Complex Oscillating Pulse');  
                string_th{end+1}=sprintf('Solid Rocket Motor Dome, Static Fire Test'); 
                string_th{end+1}=sprintf('Orion Pad Abort Test');    
                string_th{end+1}=sprintf('Bike over Gravel Road');     
            end
            if(n==2) % Acoustic Time History    
                string_th{1}=sprintf('Tuning Fork');
                string_th{end+1}=sprintf('Transformer Magnetostriction');
                string_th{end+1}=sprintf('Q400 Turboprop Aircraft');
                string_th{end+1}=sprintf('Apache Helicopter Flyover');  
                string_th{end+1}=sprintf('Hoover Dam Turbine Generators');  
                string_th{end+1}=sprintf('Launch Abort System Pad One Test');  
                string_th{end+1}=sprintf('Versa Side Window Buffet');  
                string_th{end+1}=sprintf('Boeing 717-200 Turbofan Buzz');
                string_th{end+1}=sprintf('Train Horn, Doppler Shift');
            end
            if(n==3) % Pyrotechnic Shock Time History   
                string_th{1}=sprintf('Re-entry Vehicle Separation, LSC, Raw');
                string_th{end+1}=sprintf('Re-entry Vehicle Separation, LSC, Cleaned');
                string_th{end+1}=sprintf('Flight Stage Separation Shock');
                string_th{end+1}=sprintf('NASA HDBK 7005 Mid-Field Pyrotechnic Shock');
                string_th{end+1}=sprintf('Ground Separation Test');
            end
            if(n==4) % Earthquake Time History
                
                string_th{1}=sprintf('El Centro earthquake NS');
                string_th{2}=sprintf('El Centro earthquake EW');
                string_th{3}=sprintf('El Centro earthquake UP');
                string_th{4}=sprintf('El Centro earthquake Triaxial');  
                string_th{5}=sprintf('Solomon Island earthquake');   
                string_th{6}=sprintf('GR-63-Core Zone 4 Synthesis');    
            end
            if(n==5) % Stress Time History
                string_th{1}=sprintf('ASTM E 1049 85 - Rainflow Stress');
            end
            if(n==6) % PSD
            
                string_th{1}=sprintf('NAVMAT PSD Specification');
                string_th{2}=sprintf('SMC-S-016 Minimum Workmanship PSD Specification');
                string_th{3}=sprintf('MIL-STD-1540B ATP PSD Specification');
                string_th{4}=sprintf('Aircraft External Fuselage Pressure PSD in Flight');
                string_th{5}=sprintf('HALT PSD Sample');
                string_th{6}=sprintf('Flight Data Sample');
                string_th{7}=sprintf('Test Spec Sample');   
                string_th{8}=sprintf('Suborbital Vehicle Bulkhead Flight Data'); 
                string_th{9}=sprintf('Saturn V Honeycomb Sandwich Test Data');  
                string_th{10}=sprintf('NASA/TM-2009-215902 Saturn V PSDs'); 
                string_th{11}=sprintf('NASA TN-D 1836 PSDs');  
                string_th{12}=sprintf('MIL-STD-810H Common Carrier, US HWY Truck'); 
            end
            if(n==7) % SPL
            
                string_th{1}=sprintf('MIL-STD-1540C SPL, Acceptance');
                string_th{2}=sprintf('Launch Vehicle Avionics Module Liftoff SPL');
                string_th{3}=sprintf('Saturn V Reference, One-Third Octave');
                string_th{4}=sprintf('P31'); 
                string_th{5}=sprintf('P32');  
                string_th{6}=sprintf('Sample Liftoff Ground SPL');    
                string_th{7}=sprintf('LV OML SPL, Descent Transonic');     
                string_th{8}=sprintf('Payload Fairing Internal SPL');   
            end
            if(n==8) % SRS
                string_th{1}=sprintf('MIL-STD-810G Crash Hazard');
                string_th{2}=sprintf('Vandenberg AFB 1%% Damping');
                string_th{3}=sprintf('Harris 2%% Damping');
                string_th{4}=sprintf('IEEE std 693 RSS, Various');
                string_th{5}=sprintf('GR-63 Core Zone 4');    
                string_th{6}=sprintf('GR-63 Core Zone 3');    
                string_th{7}=sprintf('GR-63 Core Zones 1 & 2');    
                string_th{8}=sprintf('ANSI C37.98 Normalized');     
                string_th{9}=sprintf('Frangible Joint Source Shock, 26 grains/ft');
                string_th{10}=sprintf('NASA LSC Source Shock, 30 grains/ft'); 
                string_th{11}=sprintf('Generic Minimum Level');      
                string_th{12}=sprintf('50 in/sec');     
                string_th{13}=sprintf('Ariane 5 Payload Interface');
                string_th{14}=sprintf('RUAG Payload Adapter System 3100SX');    
            end
            if(n==9) % Wavelet Table
                string_th{1}=sprintf('wavelet_2k');
                string_th{2}=sprintf('wavelet_11k');
            end
            if(n==10) % Sound Files
                string_th{1}=sprintf('Apache Helicopter Flyover');
                string_th{end+1}=sprintf('Sine Sweep');    
                string_th{end+1}=sprintf('Transformer Hum');
                string_th{end+1}=sprintf('Tuning Fork'); 
                string_th{end+1}=sprintf('Side Window Buffeting'); 
                string_th{end+1}=sprintf('Boeing 717-200 Turbofan Buzz'); 
                string_th{end+1}=sprintf('K5LA Train Horn, Doppler Shift');
            end
            if(n==11) % Sound Power File
                string_th{1}=sprintf('Sound Power Sample');
            end

            try
                app.SelectArrayListBox.Items=string_th;
            catch
                n
            end
        end
        
        function acoustics_select(app)

            app.SelectAcousticAnalysisListBox.Visible='off';
            app.SelectAcousticAnalysisListBoxLabel.Visible='off';

            Items = char(app.MainListBox.Items);
            im=app.MainListBox.Value;
            [app.iaco]=find_index(Items,im);

            if(app.iaco==1)
               abox(app); 
            end
            
            if(app.iaco==2)   
                vbox(app); 
            end
            if(app.iaco==3)
                handles.s=structural_wavespeed;
                set(handles.s,'Visible','on');     
            end
            if(app.iaco==4)
                handles.s=mobility_impedance;  
                set(handles.s,'Visible','on');     
            end
            if(app.iaco==5)
                handles.s=mass_ratio_methods;  
                set(handles.s,'Visible','on');     
            end
            if(app.iaco==6)
                handles.s=structural_borne_wave_propagation;
                set(handles.s,'Visible','on');     
            end
            if(app.iaco==7)
                handles.s=SEA_models;     
                set(handles.s,'Visible','on');     
            end
            if(app.iaco==8) % radiation efficiency
                rebox(app);     
            end
            if(app.iaco==9) % loss factor
                lfbox(app);     
            end
            if(app.iaco==10) % modal density
                mdbox(app);    
            end
            if(app.iaco==11) % energy & power
                epbox(app);  
            end
            if(app.iaco==12) % toolboxes
                aco_toolbox(app);   
            end
            if(app.iaco==13) % frequency & wavelength
                fwbox(app);    
            end
            
            if(app.iaco==14) % launch vehicle acoustics
                LVbox(app); 
            end
            if(app.iaco==15) % Sandwich panel toolbox
                handles.s=sandwich_panel_toolbox;
                set(handles.s,'Visible','on'); 
            end
            if(app.iaco==16) % statistical response concentration
                handles.s=statistical_response_concentration; 
                set(handles.s,'Visible','on'); 
            end


        end
        
        function abox(app)
            string_th{1}=sprintf('Speed of Sound');
            string_th{end+1}=sprintf('Doppler Shift');
            string_th{end+1}=sprintf('Wavelength');
            string_th{end+1}=sprintf('dB & Pressure Unit Conversion');
            string_th{end+1}=sprintf('Helmholtz Resonator');
            string_th{end+1}=sprintf('Transmission Loss through Partitions');
            string_th{end+1}=sprintf('Transmission Loss & Coefficient Conversion');
            string_th{end+1}=sprintf('Transmission Loss, Breakout from Duct or Pipe');   
            string_th{end+1}=sprintf('Transmission Loss, L-Beam, Waveforms');   
            string_th{end+1}=sprintf('Noise Reduction, Partition between Source & Receiver Room');
            string_th{end+1}=sprintf('Noise Reduction, Room from External Source');
            string_th{end+1}=sprintf('Acoustic Seismic Coupling'); 
            string_th{end+1}=sprintf('Sound Power, Intensity & Pressure');   
            string_th{end+1}=sprintf('Pipe Acoustics');  
            string_th{end+1}=sprintf('Sound Pressure Reverberant Room from Point Source');  
            string_th{end+1}=sprintf('Pump Noise');
            
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';            
        end
        function vbox(app)
            string_th{1}=sprintf('Homogeneous Plate Vibroacoustics');
            string_th{2}=sprintf('Honeycomb Sandwich Panel Vibroacoustics');
            string_th{3}=sprintf('Baffled Rectangular Plate Subjected to Uniform Acoustic Pressure Field');
            string_th{4}=sprintf('Baffled Rectangular Plate Subjected to Uniform Acoustic Pressure Field, Alternate');    
            string_th{5}=sprintf('Baffled Rectangular Plate Subjected to Oblique Incidence Acoustic Pressure Field');
            string_th{6}=sprintf('Baffled or Unbaffled Rectangular Plate Subjected to Launchpad Acoustics');
            string_th{7}=sprintf('Spann Method for Component Mounting Surface Vibroacoustic Response');
            string_th{8}=sprintf('Franken Method for Cylindrical Shell Response to Acoustic Pressure Field');
            string_th{9}=sprintf('Franken Method, Multiple Shells');
            string_th{10}=sprintf('Barrett Empirical Scaling Method');
            string_th{11}=sprintf('Barrett Empirical Scaling Method, Multiple Structures');
            string_th{12}=sprintf('PSD Scaling Mass Density plus dB');
            
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on'; 
        end
        function LVbox(app)
            string_th{1}=sprintf('Speed of Sound ');
            string_th{2}=sprintf('dB & Pressure Unit Conversion');
            string_th{3}=sprintf('Launch Vehicle Liftoff Acoustics');
            string_th{4}=sprintf('Launch Vehicle Aerodynamic Flow');
            string_th{5}=sprintf('Noise Reduction, Launch Vehicle');
            string_th{6}=sprintf('Launch Vehicle Vent Box/Cavity Natural Frequencies');
            string_th{7}=sprintf('Cylinder Ring Frequency');
            string_th{8}=sprintf('Franken Method for Cylindrical Shell Response to Acoustic Pressure Field');
            string_th{9}=sprintf('Atmospheric Properties');
            string_th{10}=sprintf('Engine Noise Power');
        
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';             
        end    
        function rebox(app)
            % set(handles.text_aux,'String','Radiation Efficiency');
            string_th{1}=sprintf('Panel, Homogeneous, Baffled');
            string_th{2}=sprintf('Panel, Homogeneous, Freely-Suspended');   
            string_th{3}=sprintf('Panel, Honeycomb Sandwich, Baffled');
            string_th{4}=sprintf('Panel, Ribbed'); 
            string_th{5}=sprintf('Cylinder, Homogeneous');
            string_th{6}=sprintf('Cylinder, Honeycomb Sandwich');
         
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';             
        end        
        function lfbox(app)
            % set(handles.text_aux,'String','Loss Factor');
            string_th{1}=sprintf('Dissipation Loss Factor');
            string_th{2}=sprintf('Coupling Loss Factor');
        
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';          
        end
        function mdbox(app)
            % set(handles.text_aux,'String','Modal Density');
            string_th{1}=sprintf('Modal Density');
            string_th{2}=sprintf('Modal Overlap');    
        
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';     
        end
        function epbox(app)
            % set(handles.text_aux,'String','Energy & Power');
            string_th{1}=sprintf('Energy in a Single Subsystem');
            string_th{2}=sprintf('Power Input from Force');
            string_th{3}=sprintf('Acoustic Pressure from Energy');
            string_th{4}=sprintf('Acoustic Power from SPL');
            
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';            
        end
        function aco_toolbox(app)
            % set(handles.text_aux,'String','Toolboxes');      
            string_th{1}=sprintf('Beam & Rod');
            string_th{2}=sprintf('Panel & Plate, Homogeneous');
            string_th{3}=sprintf('Panel & Plate, Sandwich');
            string_th{4}=sprintf('Cylinder, Homogeneous');
            string_th{5}=sprintf('Cylinder, Sandwich'); 
            
            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';            
        end        
        function fwbox(app)
            % set(handles.text_aux,'String','Frequency & Wavelengths');
            string_th{1}=sprintf('Wavelength');
            string_th{2}=sprintf('Cylinder Ring Frequency');
            string_th{3}=sprintf('Critical Frequency'); 

            app.SelectAcousticAnalysisListBox.Visible='on';
            app.SelectAcousticAnalysisListBox.Items=string_th;
            app.SelectAcousticAnalysisListBoxLabel.Visible='on';                  
        end
        function add_sine(app)
            app.n_add_sine=str2num(app.NumberofSinesListBox.Value);

            for i = 1:app.n_add_sine
                for j=1:2
                    data_s{i,j} = '';     
                end  
                data_s{i,3} = '0';   
            end

            app.UITable_add_sine.Data=data_s;

        end    
        function vcorrect(app)

            Items = char(app.VelocityDisplacementCorrectionListBox.Items);
            im=app.VelocityDisplacementCorrectionListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                app.HighPassFreqHzEditField.Visible='on';
                app.HighPassFreqHzEditFieldLabel.Visible='on';               
            else
                app.HighPassFreqHzEditField.Visible='off';
                app.HighPassFreqHzEditFieldLabel.Visible='off';                
            end    

        end    
        function ClearAux(app)
            app.AnalyzeSignal2Button.Visible='off';
            app.SelectSignalAnalysis2ListBox.Visible='off';
            app.SelectSignalAnalysis2ListBoxLabel.Visible='off';
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_list(app);
            ChangeManualData(app);

            msweep(app);
            library_read(app);
            plot(app);
            fatigue(app);
            NS(app);
            toolbox(app);
            inputdomain(app);
            NG(app);
            spring_mass(app);
            GenerateChange(app);
            app.GenerateTabGroup.SelectedTab=app.GenerateTabGroup.Children(1);
            app.GenerateListBox.Value=app.GenerateListBox.Items(1);
            clear(app);
            LB_change(app);
            ChangeNumberCoordinates(app);
            ChangeTimeOption(app); 
            ChangeFilter(app);
            Sarkani(app);
            update_add_white(app);
            update_add_sine(app);   
            IEEE(app);
            student_method(app);
            add_sine(app);
            vcorrect(app);
            units_force(app);

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITableManual,s) % adjust the style
        end

        % Callback function
        function VibroacousticsWikiButtonPushed(app, event)
            url = 'https://wiki.blueorigin.com/display/EPM/Vibroacoustics';
            web(url);
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            export_junction_a;
        end

        % Value changed function: SelectArrayListBox
        function ReadDataButtonPushed(app, event)
            Items = char(app.SelectTypeListBox.Items);
            im=app.SelectTypeListBox.Value;
            [p]=find_index(Items,im);
            
            Items = char(app.SelectArrayListBox.Items);
            im=app.SelectArrayListBox.Value;
            [n]=find_index(Items,im);
            
            read_data_function(p,n);
        end

        % Value changed function: SelectTypeListBox
        function SelectTypeListBoxValueChanged(app, event)
            library_read(app);
        end

        % Button pushed function: ClearApplicationDataButton
        function ClearApplicationDataButtonPushed(app, event)
            appdata = get(0,'ApplicationData');
            fnsx = fieldnames(appdata);
            for ii = 1:numel(fnsx)
              rmappdata(0,fnsx{ii});
            end
            % appdata = get(0,'ApplicationData') %make sure it's gone
            
            msgbox('Data Cleared'); 
        end

        % Button pushed function: CloseAllPlotsButton
        function CloseAllPlotsButtonPushed(app, event)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end
            msgbox('Plots Cleared');             
        end

        % Button down function: AcousticsTab
        function AcousticsTabButtonDown(app, event)
            acoustics_select(app);
            clear(app);
        end

        % Value changed function: MainListBox
        function MainListBoxValueChanged(app, event)
            acoustics_select(app);
        end

        % Callback function: AnalyzeAcousticsButton, 
        % ...and 1 other component
        function AnalyzeAcousticsButtonPushed(app, event)

            Items = char(app.SelectAcousticAnalysisListBox.Items);
            im=app.SelectAcousticAnalysisListBox.Value;
            [m]=find_index(Items,im);

            if(app.iaco==1)
                if(m==1)
                    handles.s=speed_sound;    
                end 
                if(m==2)
                    vibrationdata_doppler_App_1;    
                end 
                if(m==3)
                    handles.s=wavelength;    
                end 
                if(m==4)
                    handles.s=dB_pressure_unit_conversion;    
                end 
                if(m==5)
                    handles.s=Helmholtz_Resonator;    
                end 
                if(m==6)
                    handles.s=transmission_loss_main;    
                end 
                if(m==7)
                    handles.s=transmission_loss_coefficient;    
                end 
                if(m==8)
                    handles.s=pipe_breakout_transmission_loss;    
                end      
                if(m==9)
                    handles.s=transmission_loss_waveforms;    
                end     
                if(m==10)
                    handles.s=noise_reduction_source_receiver;    
                end 
                if(m==11)
                    handles.s=noise_reduction_room;    
                end 
                if(m==12)
                    acoustic_seismic_coupling_a;    
                end   
                if(m==13)
                    sound_pressure_intensity_a;    
                end
                if(m==14)
                    handles.s=pipe_acoustics;    
                end
                if(m==15)
                    handles.s=sound_pressure_reverberant_room;    
                end    
                if(m==16)
                    pump_noise_App;    
                end              
            end
            
            if(app.iaco==2)
                if(m==1)
                    handles.s=panel_toolbox;
                end  
                if(m==2)
                    handles.s=sandwich_panel_toolbox;
                end
                if(m==3)
                    rectangular_plate_uniform_pressure_a;
                end
                if(m==4)
                    handles.s=vibrationdata_rectangular_plate_uniform_pressure_alt;
                end    
                if(m==5)
                    handles.s=vibrationdata_rectangular_plate_oblique_incidence;
                end
                if(m==6)
                    handles.s=launchpad_baffled_plate;
                end    
                if(m==7)
                    Spann_method_a;
                end
                if(m==8)
                    Franken_method_a;
                end
                if(m==9)
                    handles.s=Franken_method_multiple;
                end
                if(m==10)
                    handles.s=Barrett_method;
                end
                if(m==11)
                    handles.s=Barrett_method_multiple;
                end
                if(m==12)
                    handles.s=psd_scaling_mass_density_plus_dB;
                end    
            end
                
            if(app.iaco==8) % radiation efficiency
                if(m==1)
                    handles.s=radiation_efficiency_panel;
                end
                if(m==2)
                    handles.s=radiation_efficiency_panel_free;
                end
                if(m==3)
                    handles.s=radiation_efficiency_honeycomb_sandwich_panel;
                end
                if(m==4)
                    handles.s=radiation_efficiency_ribbed_panel;
                end
                if(m==5)
                    handles.s=radiation_efficiency_cylinder;
                end
                if(m==6)
                    handles.s=radiation_efficiency_honeycomb_sandwich_cylinder;
                end
            end
            if(app.iaco==9) % loss factor
                if(m==1)
                    handles.s=dissipation_loss_factor;      
                end
                if(m==2)
                    handles.s=coupling_loss_factor;      
                end
            end
            if(app.iaco==10) % modal density
                if(m==1)
                    modal_density_a;      
                end
                if(m==2)
                    handles.s=modal_overlap;      
                end
            end
            if(app.iaco==11) % energy & power
                if(m==1)
                    handles.s=energy_subsystem;      
                end
                if(m==2)
                    handles.s=power_input_from_force_main;      
                end
                if(m==3)
                    handles.s=acoustic_pressure_from_energy;      
                end
                if(m==4)
                    handles.s=acoustic_power_from_SPL;      
                end       
            end
            if(app.iaco==12) % toolboxes
                if(m==1)
                    handles.s=beam_rod_toolbox;      
                end
                if(m==2)
                    handles.s=panel_toolbox;      
                end
                if(m==3)
                    handles.s=sandwich_panel_toolbox;      
                end
                if(m==4)
                    handles.s=cylinder_toolbox;      
                end
                if(m==5)
                    handles.s=sandwich_cylinder_toolbox;      
                end    
            end
            if(app.iaco==13) % frequency & wavelength
                if(m==1)
                    handles.s=wavelength;      
                end
                if(m==2)
                    cylinder_ring_frequency_a;     
                end
                if(m==3)
                    handles.s=critical_frequency;      
                end
            end
            if(app.iaco==14) % launch vehicle acoustics
                if(m==1)
                    handles.s=speed_sound;    
                end 
                if(m==2)
                    handles.s=dB_pressure_unit_conversion;    
                end 
                if(m==3)
                    handles.s=liftoff;    
                end 
                if(m==4)
                    aerodynamic_flow_a;    
                end 
                if(m==5)
                    handles.s=noise_reduction;    
                end 
                if(m==6)
                    handles.s=launch_vehicle_vent_box_fn; 
                end 
                if(m==7)
                    cylinder_ring_frequency_a;
                end
                if(m==8)
                    Franken_method_a;
                end
                if(m==9)
                    handles.s=vibrationdata_atmospheric_properties;
                end    
                if(m==10)
                    handles.s=engine_noise_power;
                end        
            end    
            
            try
                set(handles.s,'Visible','on'); 
            catch
            end
           
        end

        % Button down function: PlotTab
        function PlotTabButtonDown(app, event)

        end

        % Callback function
        function PlotIButtonPushed(app, event)
            Items = char(app.SelectPlotTypeIListBox.Items);
            im=app.SelectPlotTypeIListBox.Value;
            [n]=find_index(Items,im);

                if(n==1) % time history
                    plot_time_history_ah;  
                end
                if(n==2) % two time histories, scatter plot
                    handles.s= vibrationdata_plot_scatter;    
                end
                if(n==3) % 1D array
                    plot_1D_array;  
                end              
                if(n==4) % PSD
                    psd_plot_App;
                end
                if(n==5) % PSD, multiple
                    handles.s=vibrationdata_PSD_plot_multiple;    
                end    
                if(n==6) % SRS
                   handles.s=vibrationdata_SRS_plot;
                end
                if(n==7) % SRS, multiple
                   handles.s=vibrationdata_SRS_plot_multiple;
                end
                if(n==8) % SRS, Two, dB Difference
                   handles.s=vibrationdata_SRS_two_dB_plot;
                end    
                if(n==9) % SRS, Tripartite, multiple
                   srs_tripartite_plot_multiple;
                end               
                if(n==10) % FDS, multiple
                   handles.s=vibrationdata_FDS_plot_multiple;
                end
                if(n==11) % FDS, subplots 2x2, two curves
                   handles.s=fds_subplots_2x2_two_curves;
                end
                if(n==12) % FDS, subplots 2x2, two curves, dB Difference
                   handles.s=fds_subplots_2x2_two_curves_alt;
                end    
                if(n==13) % SPL
                   oaspl_a;  
                end
                if(n==14) % SPL, multiple
                   handles.s=vibrationdata_SPL_plot_multiple;  
                end   
                if(n==15) % FT magnitude & phase
                   handles.s=vibrationdata_FT_magnitude_phase;   
                end
                if(n==16) % FT real & imaginary
                   handles.s=vibrationdata_FT_real_imaginary; 
                end
                if(n==17) % Nyquist & Nichols Plots
                   handles.s=vibrationdata_Nyquist_Nichols; 
                end
                if(n==18) % Two Curves & Miscellaneous
                    handles.s= plot_two_curves;   
                end
                if(n==19) % Multiple Curves
                    handles.s=plot_multiple_curves;
                end 
                if(n==20) % Multiple Curves alt
                    handles.s=plot_multiple_curves_alt;
                end 
                if(n==21)                  
                    multiple_plot_App;       
                end    

                try
                    set(handles.s,'Visible','on');
                catch
                end 
        end

        % Callback function
        function PlotIIButtonPushed(app, event)
            Items = char(app.SelectPlotTypeIIListBox.Items);
            im=app.SelectPlotTypeIIListBox.Value;
            [n]=find_index(Items,im);

                if(n==1)% subplots_2x1
                    handles.s=subplots_2x1;
                end
                if(n==2)% subplots_3x1
                    handles.s=subplots_3x1;
                end  
                if(n==3)% subplots_2x2
                    handles.s=subplots_2x2;
                end 
                if(n==4)% subplots_2x3
                    handles.s=subplots_2x3;
                end  
                if(n==5)% subplots_3_and_2
                    handles.s=subplots_3_and_2;
                end         
                if(n==6)% subplots_2x3
                    handles.s=subplots_alt;
                end
                if(n==7) % subplot_1x2_two_curves_each_side
                    handles.s=subplot_1x2_two_curves_each_side;
                end    
                if(n==8)% one third octave_bar
                    handles.s=plot_one_third_octave_bar;
                end
                if(n==9)% one third octave_bar, subplots_2x1
                    handles.s=plot_one_third_octave_bar_subplots_2x1;
                end       
                if(n==10)% one third octave_bar, subplots_3x1
                    handles.s=plot_one_third_octave_bar_subplots_3x1;
                end
                if(n==11)% double Y-plot
                    handles.s=double_y_plot;
                end
                if(n==12)% double Y-plot
                    handles.s=double_y_subplot_1x2;
                end
                if(n==13)% triple Y-plot
                    handles.s=triple_y_subplot_1x3;
                end   
                if(n==14)% gnuplot
                    handles.s=plot_sine_on_random;
                end         
                if(n==15)% gnuplot
                    handles.s=gnuplot_preprocessor;
                end       
                
                try
                    set(handles.s,'Visible','on');
                catch
                end 
        end

        % Value changed function: SelectFatigueAnalysisListBox
        function SelectFatigueAnalysisListBoxValueChanged(app, event)
            fatigue_change(app);
        end

        % Callback function: AnalyzeFatigueButton, FatigueAuxListBox
        function AnalyzeFatigueButtonPushed(app, event)
            m=app.ifat;

            Items = char(app.FatigueAuxListBox.Items);
            im=app.FatigueAuxListBox.Value;
            [n]=find_index(Items,im); 
            
            
            if(m==1) % General Time History Input, Single Axis
            
                if(n==1)
                    rainflow_app; 
                end  
                if(n==2)
                    FDS_App;
                end  
                if(n==3)
                    handles.s=simple_range_mean_counting;
                end
                if(n==4)
                    handles.s=range_pair_counting;
                end
                if(n==5)
                    handles.s=batch_rainflow;
                end
                
            end
            if(m==2) % Stress Time History Input, Single Axis
                
                if(n==1)
                    handles.s=vibrationdata_rainflow_Miners_Basquin;
                end        
                if(n==2)
                    rainflow_app; 
                end  
                if(n==3)
                    handles.s=Fatigue_Damage_NASGRO_LB_input;
                end    
                if(n==4)
                    handles.s=rainflow_NASGRO_LB_output;
                end      
                if(n==5)
                    handles.s=batch_rainflow;
                end 
            
            end
            if(m==3) % Stress Time History Input, Multiaxis
                
                if(n==1)
                    handles.s=multiaxis_rainflow;
                end  
                if(n==2)
                    handles.s=multiaxis_fatigue_damage_Basquin;
                end  
                if(n==3)
                    handles.s=multiaxis_fatigue_damage_nasgro;
                end      
            
            end
            if(m==4) % Equivalence & Conversion Methods
            
                if(n==1)
                    handles.s=equivalent_sine_damage;
                end  
                if(n==2)
                    handles.s=fatigue_sine_to_narrowband;
                end  
                if(n==3)
                    handles.s=sine_on_random_composite_psd;
                end 
                if(n==4)
                    handles.s=vibrationdata_srs_fds;  
                end    
                
            end
            if(m==5) % PSD Input
            
                if(n==1)
                    vrs_fds_base;
                end  
                if(n==2)
                    stress_psd_fatigue_Basquin;
                end  
                if(n==3)
                    stress_psd_fatigue_nasgro_a;
                end  
                if(n==4)
                    handles.s=vibrationdata_stress_psd_fatigue_mean;
                end 
                if(n==5)
                    handles.s=vibrationdata_fatigue_life;
                end 
                if(n==6)
                    handles.s=vibrationdata_fatigue_life_nonGaussian;
                end 
                if(n==7)
                    response_PSD_relative_damage_a;
                end 
                if(n==8)
                    handles.s=Dirlik_batch_main;
                end 
                if(n==9)
                    handles.s=vibrationdata_accel_psd_fatigue_nasgro;
                end
                if(n==10)
                    handles.s=sine_on_random_composite_psd;
                end    
            end
            if(m==6)
                if(n==1)
                    handles.s=multiple_fds_one_segment;      
                end
                if(n==2)
                    handles.s=multiple_fds_two_segments;      
                end  
                if(n==3)
                    handles.s=multiple_fds_three_segments;      
                end    
                if(n==4)
                    handles.s=multiple_fds_one_event;        
                end   
                if(n==5)
                    handles.s=multiple_fds_two_events;        
                end    
                if(n==6)
                    handles.s=multiple_fds_four_events;        
                end     
                if(n==7)
                    handles.s=FDS_envelope;      
                end
                if(n==8)
                    handles.s=FDS_envelope_alt;      
                end
                if(n==9)
                    handles.s=FDS_envelope_multiple_sensors;      
                end
                if(n==10)
                    FDS_App;      
                end    
                if(n==11)
                    envelope_fds_sine_a; 
                end
                if(n==12)
                    handles.s=multiple_fds_damage_summation;      
                end    
                if(n==13)
                    handles.s=vibrationdata_FDS_plot_multiple;
                end    
                if(n==14) % FDS, subplots 2x2, two curves
                   handles.s=fds_subplots_2x2_two_curves;
                end
                if(n==15) % FDS, subplots 2x2, two curves, dB Difference
                   handles.s=fds_subplots_2x2_two_curves_alt;
                end 
                try
                    set(handles.s,'Visible','on');
                catch
                end
            end    
            if(m==7) % Miscellaneous
            
                if(n==1)
                    handles.s=vibrationdata_miners;
                end  
                if(n==2)
                    handles.s=pure_sine_stress_Basquin;  
                end
                if(n==3)
                    handles.s=normal_shear_stress_constant;  
                end
                if(n==4)
                    handles.s=normal_shear_stress_constant_out_phase;  
                end
                if(n==5)
                    handles.s=sn_curvefit_NASGRO;  
                end
            end
            
            try
                set(handles.s,'Visible','on'); 
            catch
            end

        end

        % Value changed function: SelectLVAnalysisListBox
        function SelectLVAnalysisListBoxValueChanged(app, event)
            NS(app);
        end

        % Callback function: LVAnalyzeButton, LVAuxListBox
        function NSAnalyzeButtonPushed(app, event)

            Items = char(app.LVAuxListBox.Items);
            im=app.LVAuxListBox.Value;
            [n]=find_index(Items,im);

            if(app.ins==1)
 
                if(n==1)
                    handles.s=vibrationdata_extract_segment_multiple_alt;      
                end   
                if(n==2)
                    handles.s=vibrationdata_bessel_filter_multiple;      
                end    
                if(n==3)
                    handles.s=vibrationdata_butterworth_filter_multiple;      
                end     
                if(n==4)
                    handles.s=vibrationdata_sdof_base_multiple;      
                end    
                if(n==5)
                    handles.s=multiple_fds_one_segment;      
                end
                if(n==6)
                    handles.s=multiple_fds_two_segments;      
                end  
                if(n==7)
                    handles.s=multiple_fds_three_segments;      
                end    
                if(n==8)
                    handles.s=multiple_fds_one_event;        
                end   
                if(n==9)
                    handles.s=multiple_fds_two_events;        
                end    
                if(n==10)
                    handles.s=multiple_fds_four_events;        
                end     
                if(n==11)
                    handles.s=FDS_envelope;      
                end
                if(n==12)
                    handles.s=FDS_envelope_alt;      
                end    
                if(n==13)
                    handles.s=FDS_envelope_multiple_sensors;
                end
                if(n==14)
                    psd_envelope_via_fds;      
                end
                if(n==15)  
                    handles.s=response_PSD_plus_min_workmanship_PSD;    
                end
                if(n==16)
                    handles.s=maximum_envelope_alt;  
                end    
                if(n==17)
                    [fig_num]=smc_s_16_psd_function();
                end
                if(n==18) 
                   dboct_a_psd;
                end         
                if(n==19)
                    handles.s=vibrationdata_PSD_plot_multiple;  
                end     
                if(n==20)
                    handles.s=vibrationdata_darray;    
                end    
                if(n==21)
                    handles.s=vibrationdata_tvfa_multiple;
                end
                
                try
                    set(handles.s,'Visible','on');
                catch
                end          
            end
            if(app.ins==2)     
                if(n==1)
                    handles.s=vibrationdata_extract_segment_multiple;      
                end
                if(n==2)
                    handles.s=vibrationdata_extract_segment_multiple_cursor;      
                end
                if(n==3)
                    handles.s=vibrationdata_edit_time_history_multiple;      
                end        
                if(n==4)
                    handles.s=vibrationdata_bessel_filter_multiple;      
                end       
                if(n==5)
                    handles.s=vibrationdata_sdof_base_multiple;      
                end    
                if(n==6)
                    handles.s=vibrationdata_srs_multiple;      
                end    
                if(n==7)
                    handles.s=srs_envelope_plus;      
                end      
                if(n==8)
                    handles.s=vibrationdata_shock_scale;      
                end   
                if(n==9)
                    handles.s=maximum_envelope_alt;  
                end    
                if(n==10) 
                   dboct_a_srs;
                end      
                if(n==11) % SRS, multiple
                   handles.s=vibrationdata_SRS_plot_multiple;
                end    
                if(n==12)
                    handles.s=vibrationdata_darray;    
                end    
                try
                    set(handles.s,'Visible','on');
                catch
                end            
            end    

        end

        % Callback function: ToolboxAnalyzeButton, ToolboxesListBox
        function ToolboxAnalyzeButtonPushed(app, event)
            Items = char(app.ToolboxesListBox.Items);
            im=app.ToolboxesListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                app.TabGroup.SelectedTab=app.GenerateTab;
            end  
            if(n==2)
                app.TabGroup.SelectedTab=app.ShockTab;              
            end 
            if(n==3)
                sdof_response_various_a;    
            end 
            if(n==4)
               Steinberg_app;    
            end 
            if(n==5)
                handles.s=nastran_toolbox;    
            end 
            if(n==6)
                handles.s=sandwich_panel_toolbox;    
            end 
            if(n==7)
                handles.s=envelope_toolbox;    
            end 
            if(n==8)
                handles.s=dboct_a;    
            end 
            if(n==9)
                vibrationdata_stress_velocity_a;    
            end     
            if(n==10)
                handles.s=modal_toolbox;    
            end 
            if(n==11)
                handles.s=SMC_S_016_tools;    
            end 
            
            try
                set(handles.s,'Visible','on');
            catch
            end
             
            
        end

        % Callback function: MiscIAnalyzeButton, MiscellaneousIListBox
        function MiscIAnalyzeButtonPushed(app, event)
            Items = char(app.MiscellaneousIListBox.Items);
            im=app.MiscellaneousIListBox.Value;
            [n]=find_index(Items,im);        

            if(n==1)
                handles.s= modal_frf_main;
            end 
            if(n==2)
                damping_functions_a;
            end 
            if(n==3)
                amplitude_conversion_utilities_a;
            end
            if(n==4)
                sine_sweep_parameters_ah;
            end
            if(n==5)
                handles.s=dboct_a;    
            end 
            if(n==6)
                statistical_distributions_ah;  
            end 
            if(n==7)
                handles.s=wavelength;    
            end 
            if(n==8)
                handles.s=vibrationdata_rotation;    
            end 
            if(n==9)
                handles.s=sound_editor_ah;    
            end
            if(n==10)
                handles.s=aliasing_frequencies;    
            end 
            if(n==11)
                handles.s=wind_waves;    
            end 
            if(n==12)
                handles.s=ESS_screening_strength;    
            end 
            if(n==13)
                handles.s=flight_orbital_mechanics;
            end 

            try
                set(handles.s,'Visible','on'); 
            catch
            end

        end

        % Callback function: MiscIIAnalyzeButton, MiscellaneousIIListBox
        function MiscIIAnalyzeButtonPushed(app, event)
            Items = char(app.MiscellaneousIIListBox.Items);
            im=app.MiscellaneousIIListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                handles.s=vibrationdata_linear_algebra;      
            end
            if(n==2)
                handles.s=structural_static_analysis;      
            end
            if(n==3)
                handles.s=vibrationdata_fracture;      
            end
            if(n==4)
               handles.s=traveling_wave_animation; 
            end
            if(n==5)
                handles.s=burst_overpressure;    
            end 
            if(n==6)
                handles.s=fluid_systems;    
            end 
            if(n==7)
                handles.s=vibrationdata_panel_flutter;    
            end 
            if(n==8)
                vibrationdata_unit_conversion_App;   
            end 
            if(n==9)
                time_conversion_a;    
            end 
            if(n==10)
                handles.s=vibrationdata_atmospheric_properties;    
            end 
            if(n==11)
                handles.s=vibrationdata_reynolds;    
            end 
            if(n==12)
                Strouhal;    
            end    
            if(n==13)
                handles.s=vibrationdata_interpolate_coordinates;    
            end          
            if(n==14)
                handles.s=vibrationdata_interpolate_function;    
            end 
            if(n==15)
                handles.s=vibrationdata_darray;    
            end
            if(n==16)
                    try
                        handles.s=digitize_plot;
                    catch
                    end
            end
            if(n==17)
                    try
                        handles.s=stress_strain_to_energy_strain;
                    catch
                    end
            end                
            if(n==18)
                handles.s=vibe_test_array;    
            end           

            try
                set(handles.s,'Visible','on'); 
            catch
            end


        end

        % Value changed function: SelectInputDomainListBox
        function SelectInputDomainListBoxValueChanged(app, event)
            amplitude(app);
        end

        % Callback function: AnalyzeSignal1Button, 
        % ...and 1 other component
        function AnalyzeSignalButtonPushed(app, event)

            ClearAux(app);

            n=app.indomain;
         
            Items = char(app.SelectSignalAnalysis1ListBox.Items);
            im=app.SelectSignalAnalysis1ListBox.Value;
            [app.isignal]=find_index(Items,im);

            Items = char(app.SelectAmplitudeTypeListBox.Items);
            im=app.SelectAmplitudeTypeListBox.Value;
            [app.iamplitude]=find_index(Items,im);
             
            if(n==1) % time history
                time_history_options(app);
            end   
            if(n==2) % psd
                psd_options(app);
            end   
            if(n==3) % spl
                spl_options(app);
            end  
            if(n==4) % srs
                srs_options(app);
            end  
            if(n==5) % Fourier transform
                FT_options(app);
            end 
            if(n==6) % wavelet table
                WT_options(app);
            end
            if(n==7) % fds
                fds_options(app);
            end 
            if(n==8) % fds
                vrs_options(app);
            end 
            if(n==9) % sine-on-random
                sor_options(app);
            end 
            if(n==10) % sine sweep-on-random
                ssweepor_options(app);
            end            
        end

        % Value changed function: SelectAmplitudeTypeListBox
        function SelectAmplitudeTypeListBoxValueChanged(app, event)
            signal_analysis(app);            
        end

        % Callback function
        function NGVibrationAnalyzeButtonPushed(app, event)
            Items = char(app.NewGlennVibrationListBox.Items);
            im=app.NewGlennVibrationListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                try
                    set(Saturn_V_data_import,'Visible','on');
                catch
                end
            end 
            if(n==2)
                try
                    set(NG_spl_psd_data_import,'Visible','on');
                catch
                end
            end   
            if(n==3)
                try
                    set(Barrett_method,'Visible','on');
                catch
                end
            end     
            if(n==4)
                try
                    set(vibrationdata_PSD_plot_multiple,'Visible','on');  
                catch
                end
            end  
            if(n==5)
                try
                    set(psd_max_interp,'Visible','on');  
                catch
                end
            end    

        end

        % Callback function
        function NGAcousticsAnalyzeButtonPushed(app, event)
            Items = char(app.NewGlennAcousticsListBox.Items);
            im=app.NewGlennAcousticsListBox.Value;
            [n]=find_index(Items,im);        

            if(n==1)  % Import NG GS1 liftoff & landing SPLs
                
                fig_num=1;
                
                NG_GS1_external_liftoff_SPL=...
                [   20	131.2
                    25	132.9
                    31.5	135.9
                    40	138.9
                    50	141.9
                    63	145.2
                    80	147.3
                    100	148.0
                    125	148.8
                    160	149.7
                    200	150.3
                    250	150.7
                    320	151.2
                    400	151.7
                    500	152.2
                    640	153.0
                    800	153.8
                    1000	154.9
                    1250	156.4
                    1600	158.9
                    2000	159.7];    
           
                
                NG_GS1_external_landing_SPL=...
                [   20 	     132.4 
                    25 	     133.2 
                    31.5 	 137.8 
                    40 	     144.1 
                    50 	     151.1 
                    63 	     158.7 
                    80 	     164.8 
                    100 	 167.9 
                    125 	 169.1 
                    160 	 167.8 
                    200 	 164.9 
                    250 	 161.6 
                    320 	 159.2 
                    400 	 159 
                    500 	 160.5 
                    640 	 163.1 
                    800 	 166.3 
                    1000 	 169.1 
                    1250 	 171.3 
                    1600 	 172.7 
                    2000 	 170 ];
                
                output_name{1}='NG_GS1_external_liftoff_SPL';
                assignin('base', output_name{1}, NG_GS1_external_liftoff_SPL );       
                output_name{2}='NG_GS1_external_landing_SPL';
                assignin('base', output_name{2}, NG_GS1_external_landing_SPL );  
                                      
                n_type=1;
            
                tstring='NG GS1 BHS MPE External Levels';
                
                leg1='Liftoff';
                leg2='Landing';
                
                f1=NG_GS1_external_liftoff_SPL(:,1);
                dB1=NG_GS1_external_liftoff_SPL(:,2);
                
                f2=NG_GS1_external_landing_SPL(:,1);
                dB2=NG_GS1_external_landing_SPL(:,2);
                
                stype=1;
            
                [fig_num,~]=spl_plot_two_title_alt(fig_num,n_type,f1,dB1,f2,dB2,leg1,leg2,tstring,stype);
            end 
            if(n==2)
                handles.s=liftoff;        
            end    
            if(n==3)
                handles.s=landing_acoustics;        
            end 
            if(n==4)
                handles.s=psd_scaling_mass_density_plus_dB;        
            end
            try
                set(handles.s,'Visible','on');
            catch
            end

        end

        % Callback function
        function AnalyzeButtonStructuresPushed(app, event)
            Items = char(app.SelectAnalysisStructuresListBox.Items);
            im=app.SelectAnalysisStructuresListBox.Value;
            [m]=find_index(Items,im);
            
            if(m==1)
               handles.s=beam_longitudinal;
            end
            if(m==2)
               handles.s=shaft_torsional;
            end
            if(m==3)
               handles.s=Holzer_torsional;
            end 
            if(m==4)
               handles.s=beam_bending_menu;
            end 
            if(m==5)
               handles.s=structural_dynamics_plates;
            end 
            if(m==6)
               handles.s=vibrationdata_inplane_rectangular_plate_fea_app;
            end 
            if(m==7)
               handles.s=rings_cylinders;
            end  
            if(m==8)
               handles.s=tall_building;
            end  
            set(handles.s,'Visible','on');

        end

        % Callback function: AnalyzeButtonEigen, SelectAnalysisEigenListBox
        function AnalyzeButtonEigenPushed(app, event)
   
           Items = char(app.SelectAnalysisEigenListBox.Items);
           im=app.SelectAnalysisEigenListBox.Value;
           [m]=find_index(Items,im);
        
           if(m==1)
                vibrationdata_generalized_eigenvalue_App;
           end  
           if(m==2)
                handles.s=vibrationdata_standard_eigenvalue;
           end  
           if(m==3)
                handles.s=vibrationdata_convert_general_standard;
           end  
           if(m==4)
                handles.s=vibrationdata_tridiagonal;
           end    
           if(m==5)
                handles.s=modified_Gram_Schmidt_orthogonalization;
           end      
           try
                set(handles.s,'Visible','on')
           catch
           end            
        end

        % Callback function: AnalyzeButtonNastran, 
        % ...and 1 other component
        function AnalyzeButtonNastranPushed(app, event)
        
           Items = char(app.SelectAnalysisNastranListBox.Items);
           im=app.SelectAnalysisNastranListBox.Value;
           [m]=find_index(Items,im);
                        
            if(m==1)
                handles.s=nastran_normal_modes;
            end
            if(m==2)
                handles.s=nastran_normal_modes_response_spectrum;
            end
            if(m==3)
                handles.s=nastran_modal_transient;
            end
            if(m==4)
                handles.s=nastran_base_input_frf;
            end
            if(m==5)
                handles.s=nastran_base_input_psd;
            end 
            set(handles.s,'Visible','on');            
        end

        % Callback function: AnalyzeButtonTrans, SelectAnalysisTransListBox
        function AnalyzeButtonTransPushed(app, event)

            Items = char(app.SelectAnalysisTransListBox.Items);
            im=app.SelectAnalysisTransListBox.Value;
            [m]=find_index(Items,im);
        
            if(m==1)
                transfer_functions_from_modes_a;
            end  
            if(m==2)
                handles.s=vibrationdata_blast;
            end
            if(m==3)
                frf_state_space;
            end         
            try
                set(handles.s,'Visible','on');
            catch
            end
        end

        % Callback function: AnalyzeButtonMisc, SelectAnalysisMiscListBox
        function AnalyzeButtonMiscPushed(app, event)
            
           Items = char(app.SelectAnalysisMiscListBox.Items);
           im=app.SelectAnalysisMiscListBox.Value;
           [m]=find_index(Items,im);
        
           if(m==1)
               damping_coefficient_matrix_a;
           end  
           if(m==2)
               vibrationdata_stress_velocity_a;
           end  
           if(m==3)
               handles.s=Dunkerley;
           end   
           if(m==4)
               handles.s=area_moment_inertia;
           end     

           try
               set(handles.s,'Visible','on');
           catch
           end
        end

        % Callback function: AnalyzeButton, SelectSystemListBox
        function AnalyzeButtonPushed(app, event)
           Items = char(app.SelectSystemListBox.Items);
           im=app.SelectSystemListBox.Value;
           [m]=find_index(Items,im);
            
            if(app.ndof==1)
                if(m==1) %1
                    sdof_fn_a;
                end
                if(m==2) %2
                    sdof_response_various_a;
                end
                if(m==3) %3
                    classical_pulse_base_input_a;
                end
                if(m==4) %4
                    sdof_response_time_domain;
                end
                if(m==5) %5
                    classical_pulse_applied_force_a;
                end
                if(m==6) %6
                    sdof_response_time_domain;
                end
                if(m==7) %7
                    vrs_fds_base;
                end
                if(m==8) %8
                    sdof_response_various_a;       
                end
                if(m==9) %9
                    sdof_transmissibility;
                end                
                if(m==10) %10
                    handles.s=vibrationdata_spring_surge;
                end
                if(m==11) %11
                    accidental_drop_shock_a;
                end     
                if(m==12) %12
                    vrs_fds_force;
                end    
                                
            end
            if(app.ndof==2)
                if(m==1)
                    two_dof_system_ah;   
                end   
                if(m==2)
                    two_dof_force_a;   
                end 
                if(m==3)
                    two_dof_system_dashpots_app;   
                end   
                if(m==4)
                    handles.s=three_parameter_isolation_system;   
                end    
                if(m==5)
                    handles.s=automobile_road;   
                end                 
            end 
            if(app.ndof==3)
                if(m==1)
                    three_dof_system_a;                   
                end 
                if(m==2)
                    three_dof_base_ah;                   
                end
                if(m==3)
                    handles.s=ESA_simple_spacecraft_model;                   
                end
                if(m==4)
                    handles.s=ESA_simple_launcher_spacecraft_model;                   
                end
                if(m==5)
                    handles.s=semi_definite_three_dof_applied_force;                   
                end               
            end
            if(app.ndof==4)
                if(m==1)
                    handles.s=six_dof_four_isolators;                   
                end 
                if(m==2)
                    handles.s=vibrationdata_arbitrary_isolators;
                end  
            end
            if(app.ndof==5)
                if(m==1)
                    nm_mdof_arbitrary_single_force_a;                   
                end 
                if(m==2)
                    nm_mdof_modal_arbitrary_force;
                end                 
                if(m==3)
                    handles.s=ri_mdof_modal_arbitrary_force;                   
                end
                if(m==4)
                    nm_mdof_arbitrary_enforced_motion_a;                   
                end
                if(m==5)
                    handles.s=nm_mdof_acceleration_force;                   
                end  
                if(m==6)
                    handles.s=vibrationdata_fea_preprocessor;                   
                end                  
            end     
            
            try
                set(handles.s,'Visible','on');
            catch        
            end            
        end

        % Value changed function: SelectNumberofDOFListBox
        function SelectNumberofDOFListBoxValueChanged(app, event)
            spring_mass(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed2(app, event)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  
            
            ReadDataButtonPushed(app, event);
        end

        % Button pushed function: ReadFileMethod1Button
        function ReadFileMethod1ButtonPushed(app, event)
           
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
            catch
            end    
            
            try
                   app.THM = readmatrix(filename);
                   msgbox('File read');              

            catch
                   warndlg('Read unsuccessful');
                   return;
            end

            try
                    [~, name, ~] = fileparts(filename);
            catch
                    warndlg('ref 1')
            end

            check = all(ismember(name(1), '0123456789'));

            if(check==1)
                ss=sprintf('The Matlab array must begin with a letter. \n array is renamed:  a%s',name);
                msgbox(ss);
                name=sprintf('a%s',name);
            end    

            try
                    assignin('base',name,app.THM);
            catch
                    warndlg('ref 2')
            end 
        end

        % Button pushed function: ReadmatButton
        function ReadmatButtonPushed(app, event)
            try
            
                [filename, pathname] = uigetfile('*.mat');
     
                NAME = [pathname,filename];
                struct=load(NAME);
                structnames = fieldnames(struct, '-full'); % fields in the struct
                
                k=length(structnames);
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end
               
                msgbox('Load complete')

            catch
                warndlg('Read failed');
            end

            try
                update_list(app);
            catch
            end
        end

        % Button pushed function: ReadFileMethod2Button
        function ReadFileMethod2ButtonPushed(app, event)
            try
                [filename, pathname] = uigetfile('*.*');
                filename = fullfile(pathname, filename);
            catch
            end    
            
            try
                app.THM = importdata(filename);
                msgbox('File read');              
            catch
                warndlg('Read unsuccessful');
                return;
            end

            try
                [~, name, ~] = fileparts(filename);
            catch
                warndlg('ref 11')
            end

            check = all(ismember(name(1), '0123456789'));

            if(check==1)
                ss=sprintf('The Matlab array must begin with a letter. \n array is renamed:  a%s',name);
                msgbox(ss);
                name=sprintf('a%s',name);
            end    


            try
                assignin('base',name,app.THM);
            catch
                warndlg('ref 22')
            end 
        end

        % Button pushed function: PlotIButton
        function PlotIButtonPushed2(app, event)
            PlotIButtonPushed(app, event);
        end

        % Value changed function: SelectPlotTypeIListBox
        function SelectPlotTypeIListBoxValueChanged(app, event)
            PlotIButtonPushed(app, event);
        end

        % Button pushed function: PlotIIButton
        function PlotIIButtonPushed2(app, event)
            PlotIIButtonPushed(app, event);
        end

        % Value changed function: SelectPlotTypeIIListBox
        function SelectPlotTypeIIListBoxValueChanged(app, event)
            PlotIIButtonPushed(app, event);
        end

        % Button pushed function: AnalyzeButtonStructures_BeamClassical
        function AnalyzeButtonStructures_BeamClassicalPushed(app, event)
            Items = char(app.SelectBeamClassicalListBox.Items);
            im=app.SelectBeamClassicalListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                beam_bending_a;
            end 
            if(n==2)
                handles.s=beam_bending_composite_laminate;
            end
            if(n==3)
                handles.s=cantilever_beam_point_mass;
            end
            if(n==4)
                beam_bending_a;
            end
            if(n==5)
                beam_simply_supported_point_mass_a; 
            end
            if(n==6)
                handles.s=Timoshenko_beam;
            end
            if(n==7)
                handles.s=vibrationdata_beam_multispan;
            end

            try
                set(handles.s,'Visible','on')
            catch
            end            
        end

        % Button pushed function: AnalyzeButtonStructures_BeamFEA
        function AnalyzeButtonStructures_BeamFEAPushed(app, event)
            Items = char(app.SelectBeamFEAListBox.Items);
            im=app.SelectBeamFEAListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                beam_bending_FEA_a;
            end
            if(n==2)
                beam_bending_FEA_springs_a;
            end
            if(n==3)
                handles.s=rotating_beam;
            end
            if(n==4)
                handles.s=beam_wavelength_FEA;
            end
            if(n==5)
                handles.s=beam_column_FEA;
            end
            if(n==6)
                handles.s=beam_column_static_inertia_relief;
            end
            if(n==7)
                handles.s=beam_column_static_inertia_relief_6dof;
            end
            if(n==8)
                multispan_beam_bending_FEA;
            end
            if(n==9)
                handles.s=multispan_beam_bends_FEA;
            end

            try
                set(handles.s,'Visible','on')
            catch
            end            
        end

        % Button pushed function: 
        % AnalyzeButtonStructures_RectangularPlateBending
        function AnalyzeButtonStructures_RectangularPlateBendingPushed(app, event)
            Items = char(app.SelectRectangularPlateListBox.Items);
            im=app.SelectRectangularPlateListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                rectangular_plate_bending_a;
            end
            if(n==2)
                rectangular_plate_uniform_pressure_a;
            end
            if(n==3)
                handles.s=rectangular_plate_uniform_pressure_large_deflection;
            end
            if(n==4)
                handles.s=vibrationdata_rectangular_plate_fea_app;
            end   
            if(n==5)
                handles.s=thick_rectangular_plate_bending_fea;
            end
           
            try
                set(handles.s,'Visible','on')
            catch
            end

        end

        % Button pushed function: AnalyzeButtonStructures_CircularPlate
        function AnalyzeButtonStructures_CircularPlatePushed(app, event)
            Items = char(app.SelectPlateListBox.Items);
            im=app.SelectPlateListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                handles.s=circular_plate_bending;
            end
            if(n==2)
                handles.s=annular_plate_bending;
            end  

            try
                set(handles.s,'Visible','on')
            catch
            end

        end

        % Button pushed function: AnalyzeButtonStructures_PlateInPlane
        function AnalyzeButtonStructures_PlateInPlanePushed(app, event)
            Items = char(app.SelectPlateTypeListBox.Items);
            im=app.SelectPlateTypeListBox.Value;
            [n]=find_index(Items,im);

            handles.s=vibrationdata_inplane_rectangular_plate_fea;
             
            try
                set(handles.s,'Visible','on')
            catch
            end

        end

        % Button pushed function: AnalyzeButtonStructures_CylindricalShell
        function AnalyzeButtonStructures_CylindricalShellPushed(app, event)
            Items = char(app.SelectStructureListBox.Items);
            im=app.SelectStructureListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                handles.s=thin_ring;
            end
            if(n==2)
                cylinder_fn_a;   
            end
            if(n==3)
               cylinder_ring_frequency_a; 
            end
            
            try
                set(handles.s,'Visible','on')
            catch
            end

        end

        % Button pushed function: AnalyzeButtonStructures_Rod
        function AnalyzeButtonStructures_RodPushed(app, event)
            Items = char(app.SelectRodAnalysisListBox.Items);
            im=app.SelectRodAnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
               handles.s=beam_longitudinal;
            end
            if(n==2)
                tapered_rod_fea_a;
            end    
            if(n==3)
               handles.s=shaft_torsional;
            end
            if(n==4)
               handles.s=Holzer_torsional;
            end           

            try
                set(handles.s,'Visible','on');
            catch
            end

        end

        % Button pushed function: AnalyzeButtonStructures_Misc
        function AnalyzeButtonStructures_MiscPushed(app, event)
            Items = char(app.SelectMiscellaneousListBox.Items);
            im=app.SelectMiscellaneousListBox.Value;
            [n]=find_index(Items,im);

            handles.s=tall_building; 
            set(handles.s,'Visible','on');
            
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            GenerateChange(app);
        end

        % Value changed function: GenerateListBox
        function GenerateListBoxValueChanged(app, event)
            
            GenerateChange(app);
        end

        % Selection change function: GenerateTabGroup
        function GenerateTabGroupSelectionChanged(app, event)


        end

        % Button pushed function: CalculateButton_sine
        function CalculateButton_sinePushed(app, event)
            clear(app);
            
            Items = char(app.RectifyListBox.Items);
            im=app.RectifyListBox.Value;
            [irec]=find_index(Items,im);
 
              amp=str2num(app.AmplitudeEditField.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField.Value);
            phase=str2num(app.PhaseAngledegEditField.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
               
            dt=1/sr;
            omega=2*pi*freq;
            phase=phase*pi/180.;
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*sin(omega*TT+phase);
            
            if(irec==2)
                a=abs(a);
            end
            
            t_string='Sine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram_sine(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditField.Value='sine';
            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';  
            app.CalculateFFTButton.Visible='on'; 

            
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplitudeEditField
        function AmplitudeEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PhaseAngledegEditField
        function PhaseAngledegEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: RectifyListBox
        function RectifyListBoxValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            vibrationdata_fourier_transform_ah;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                name=app.OutputNameEditField.Value;
                assignin('base',name,app.THM);
                update_list(app);
                msgbox('Array Saved');
            catch
            end
        end

        % Button pushed function: CalculateButton_cosine
        function CalculateButton_cosinePushed(app, event)

            clear(app);

              amp=str2num(app.AmplitudeEditField_2.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField_2.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
               
            dt=1/sr;
            omega=2*pi*freq;
         
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*cos(omega*TT);
            
            t_string='Cosine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.OutputNameEditField.Value='cosine';
            app.SaveButton.Visible='on';  
            app.CalculateFFTButton.Visible='on'; 
        end

        % Value changing function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FrequencyHzEditField_2
        function FrequencyHzEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changed function: NumberofTonesListBox
        function NumberofTonesListBoxValueChanged(app, event)
            clear(app);
            LB_change(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_multi
        function CalculateButton_multiPushed(app, event)
            clear(app);
            
            N=str2num(app.NumberofTonesListBox.Value);
            
            A=char(app.UITable.Data);
            
            B=str2num(A);
            
            peak=B(1:N);
            freq=B((N+1):(2*N)); 
            phase=B((2*N+1):(3*N)); 
            
            phase=phase*pi/180;
            
            tmax=str2num(app.DurationsecEditField.Value);
            sr=str2num(app.SampleRateHzEditField.Value); 
            ylab=app.YaxisLabelEditField.Value;

            dt=1/sr;
            if(max(freq)>(sr/20))
               sr=max(freq)*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               out1=sprintf('%8.4g',sr);
               set(handles.edit_sr,'String',out1);
            end    
       
            omega=2.*pi*freq;
        %    
            np=round(tmax/dt);
            TT=linspace(0,np*dt,(np+1)); 
            a = zeros(1,(np+1));  
        %    
            for i=1:N
                a=a+peak(i)*sin(omega(i)*TT-phase(i));
            end  
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            figure(1);
            plot(TT,a);
            if(i==2)
                tstring='Beat Frequency Effect';
            else
                tstring='Multiple Sine Tones';        
            end
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(ylab);
            
            app.THM=[TT a];
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditField.Value='sine_tones';
            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';   

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            clear(app);
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            clear(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            disp(' ') 
            disp(' * * * * * * ')
            disp(' ')
        
            clear(app);

            N=app.num;
            
            ndirs=app.SweepDirectionListBox.Value;
            
            if(strcmp(ndirs,'up'))
                ndir=1;
            end
            if(strcmp(ndirs,'down'))
                ndir=2;
            end            
            if(strcmp(ndirs,'up & down'))
                ndir=3;
            end               
  
            tmax=app.DurationsecEditField_sweep.Value;
            
            B=app.UITable_sweep.Data;
            
            freq=B(1:N);
            amp=B((N+1):(2*N));
            
            freq=fix_size(freq);
            amp=fix_size(amp);
            
            fa=[freq amp];
            
            fa=sortrows(fa,1);
            
            app.fff=fa(:,1);
            aaa=fa(:,2);
            
            sr=app.SampleRateHzEditField_sweep.Value;
            dt=1/sr;
            
            if(max(app.fff)>(sr/10))
                   sr=max(app.fff)*10;
                   dt=1/sr;
                   msgbox('Note: sample rate increased ');
                   app.SampleRateHzEditField.Value=sr;
            end
           
            f1=app.fff(1);
            f2=app.fff(end);
            oct=log2(f2/f1);

            nto=app.TimeOptionListBox.Value;
            
            disp(' ');
            if(app.ntype==1)
                dur=tmax;
            else
                if(contains(nto,'Duration'))
                    dur=tmax;
                else    
                    dur=(oct/tmax)*60;
                    fprintf(' %7.3g octaves/min ',tmax); 
                end    
            end

            fprintf(' %6.3g octaves \n',oct);
            fprintf(' %7.3g seconds \n',dur);       
            
            app.NumberofOctavesEditField.Visible='on';
            app.NumberofOctavesEditFieldLabel.Visible='on';
            app.NumberofOctavesEditField.Value=oct;
            
            ns=ceil(dur*sr);
            
            if(ndir==3)
                ns=ns*2;
            end
            
            ntimes = ns;
            
            tpi=2.*pi;
            
            if(app.ntype==1)  % linear
                rate=(f2-f1)/dur;
            else              % log
                rate=oct/dur;
            end
            
            maxn=90000000;
            
            if(ntimes>maxn)
                ntimes=maxn;
            %    
                out1=sprintf('Time history truncated to %d points,',maxn);
                out5 = sprintf('or %g sec',maxn*dt);
                sss=strcat(out1,out5);
            %  
                msgbox(sss,'Warning','warn');        
            end
            
            if(ndir==3)
                nt=ntimes/2;
            else
                nt=ntimes;     
            end
            
            TT=linspace(dt,(ntimes+1)*dt,ntimes); 
            
               a = zeros(1,ntimes);
             arg = zeros(1,nt);
            freq = zeros(1,nt);
            
            if(app.ntype==1)  % linear
            %
            % 0.5 factor is necessary to obtain correct number of cycles for linear case.
            %
            %	fspectral= (     rate*t ) + f1;
            %
                fmax=0.5*(f2-f1)+f1;
                freq=linspace(f1,fmax,nt);     
            %
            else  % log
            %  
            %   fspectral = f1*pow(2.,rate*t);
            %
                for i=1:nt
                    arg(i)=-1.+2^(rate*TT(i));
                end   
            end
            
            freq=fix_size(freq);
            TT=fix_size(TT);
            
            if(app.ntype==1) % linear
               arg=tpi*freq.*TT(1:nt);
            else             % log
               arg=tpi*f1*arg/(rate*log(2));  
            end
            
            if(ndir==3)
                   arg1=arg;
                   arg1=fix_size(arg1);
                   arg2=flipud(arg1);       
                   arg=[arg1; arg2];
            end 
            
            ntimes=length(a);
            
            if(app.ntype==1) % linear
                spectral=linspace(f1,f2,nt); 
            else 
                f1=log10(f1);
                f2=log10(f2);
                spectral=logspace(f1,f2,nt);         
            end
            
            limit=length(app.fff)-1;
            amplitude=zeros(1,nt);
            
            for i=1:nt
               for j=1:limit
                   if(app.fff(j)<=spectral(i) && app.fff(j+1)>=spectral(i))
                        x=spectral(i)-app.fff(j);
                        L=app.fff(j+1)-app.fff(j);
                        c2=x/L;
                        c1=1-x/L;
                        amplitude(i)=c1*aaa(j)+c2*aaa(j+1);
                        break;
                   end
               end
            end
            
            if(ndir==3)
                a(1:nt)=sin(arg(1:nt));  
                
                diff_rec=1.0e+90;
                 
                for i=0:359
                    theta=pi*i/180;
            
                    a1=a(nt-1);
                    a2=a(nt);
                    a3=sin(arg(nt+1)+theta);
                    a4=sin(arg(nt+2)+theta);        
            
                    ab=[a1 a2 a3 a4];
                    aa=detrend(ab);
                    
                    diff=norm(aa);
                    
                    if(diff<diff_rec)
                        diff_rec=diff;
                        psi=theta;
                    end
                    
                end
            %    psi
            
                a((nt+1):ntimes)=sin(arg((nt+1):ntimes)+psi);
            else
                a=sin(arg);
            end
            %
            
            if(ndir==3)
                   amp1=amplitude;
                   amp1=fix_size(amp1);
                   amp2=flipud(amp1);       
                   amplitude=[amp1; amp2];    
            end
            
            a=fix_size(a);
            amplitude=fix_size(amplitude);
            
            a(1)=0.;
            a=a.*amplitude;
            TT=TT-dt;
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            if(ndir==2)
                a=flipud(a);
            end
            
            figure(1);
            plot(TT,a);
            tstring='Sine Sweep';
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel('Amplitude');
            
            app.signal=[TT a];            
            
            fig_num=2;
            
            t=TT;    
            
            numt=length(t);
            
            %
            %   positive slope zero crossing times for input
            %
            
            tindex=zeros(numt,1);
            tz=zeros(numt,1);
            
            k=1;
            for i=2:numt
                if( a(i) > a(i-1) && a(i) > 0 && a(i-1) <=0)
                    tindex(k)=i;
                    L=a(i)-a(i-1);
                    c1=a(i)/L;
                    c2=1-c1;
                    tz(k)=c1*t(i-1)+c2*t(i);
                    k=k+1;
                end
            end
            
            nt=k-1;   % number of positive slope zero crossings
            
            %% step=round(str2num(get(handles.edit_step,'String')));   % with sliding window
            
            step=ceil((1/app.fff(1))/dt)/25;
            
            if(step<20)
                step=20;
            end
 
            
            m=1;
            for i=(step+1):nt
                m=m+1;
            end
            m=m-2;  % leave
             
            aa=zeros(m,1);
            f=zeros(m,1);
            
            nspec=app.CalculateSpectrumFunctionListBox.Value;
                
            if(strcmp(nspec,'Yes'))
            
                progressbar;
            
                m=1;
                
                for i=(step+1):nt
                
                    progressbar(i/nt);
                
                    kk=tindex(i);
                    jj=tindex(i-step);
                    T=(tz(i)-tz(i-step))/step;
                    f(m)=1/T;
                    aa(m)=max(abs(a(jj:kk)));
                
                    m=m+1;
                end
            
                pause(0.2);
                progressbar(1);
                
                ppp=[f aa];
                t_string='Sine Sweep Spectral Function';
                x_label='Frequency (Hz)';
                y_label='Accel (G)';
                
                fmin=f(1);
                fmax=f(end);
                
                md=5;
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                
            end
            
            name=app.OutputArrayNameEditField.Value;
            
            assignin('base',name, app.signal); 

            app.BaseExcitationArrayEditField.Value=name;
            app.AppliedForceArrayEditField.Value=name;
            
            update_list(app);

        end

        % Value changed function: SweepTypeListBox
        function SweepTypeListBoxValueChanged(app, event)
            ChangeTimeOption(app);
        end

        % Value changed function: TimeOptionListBox
        function TimeOptionListBoxValueChanged(app, event)
            ChangeTimeOption(app);
        end

        % Value changed function: NumberofCoordinatesListBox
        function NumberofCoordinatesListBoxValueChanged(app, event)
            ChangeNumberCoordinates(app);
        end

        % Button pushed function: CalculateButton_base
        function CalculateButton_basePushed(app, event)
            disp(' ');
            disp(' * * * * * * * ');
            disp(' ');
        
            clear(app);

            nnum=str2num(app.WindowCyclesEditField.Value);
            
            iu=1;
            
            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            Q=str2num(app.AmplificationFactorQEditField.Value);
            FS=app.BaseExcitationArrayEditField.Value;

            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end 
            
            damp=1/(2*Q);
            
            dt=mean(diff(app.THM(:,1)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);
            %
            %  SRS engine
            %
            
            a_pos=zeros(1,1);
            a_neg=zeros(1,1);
            rd_pos=zeros(1,1);
            rd_neg=zeros(1,1);
            
            yy=app.THM(:,2);
            
            for j=1:1
            %
                disp(' ')
                disp(' Calculating absolute acceleration');
            %
                [a_resp,a_pos(j),a_neg(j)]=arbit_engine(a1(j),a2(j),b1(j),b2(j),b3(j),yy);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ')
                disp(' Calculating relative displacement');
            %
                [rd_resp,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),yy);

                if(iu==1)
                    scale=386;
                end
                if(iu==2)
                    scale=9.81*1000;        
                end
                if(iu==3)
                    scale=1000;        
                end    
            %
                rd_resp=rd_resp*scale;
                rd_pos(j)=rd_pos(j)*scale;
                rd_neg(j)=rd_neg(j)*scale;

            end
            %
            if(iu==1 || iu==2)
                big_string=sprintf(' Acceleration Response (G) \n');
            else
                big_string=sprintf(' Acceleration Response (m/sec^2) \n');
            end
            
            string1=sprintf('\n\n max=%8.4g',a_pos(1));
            string2=sprintf('\n min=%8.4g',-a_neg(1));
    
            big_string=strcat(big_string,string1);
            big_string=strcat(big_string,string2);
                       
            if(iu==1)
                string8=sprintf('\n\n Relative Displacement (in) \n');    
            else
                string8=sprintf('\n\n Relative Displacement (mm) \n');    
            end
                
            string9=sprintf('\n\n max=%7.3g',rd_pos(1));
            string10=sprintf('\n min=%7.3g',rd_neg(1));

            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);            
            
            fprintf('\n Q=%g \n',Q);
            
            app.ResultsTextArea_sweep.Value=big_string;
            app.ResultsTextArea_sweep.Visible='on';
            app.ResultsTextAreaLabel_sweep.Visible='on';            
            
            disp(' ');
            disp(big_string);
              
            fig_num=1;            
  
            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),a_resp);
            xlabel(' Time(sec) ');
            legend('Base Input','Response');
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            grid on;
            title('Acceleration Time History');
            
            if(iu==1 || iu==2)
                    ylabel1='Accel (G)';
                    ylabel2='Accel (G)'; 
            else
                    ylabel1='Accel (m/sec^2)';
                    ylabel2='Accel (m/sec^2)';     
            end
            
            xlabel2='Time (sec)';
            
            t_string1=('Base Input');
            t_string2=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
            
            data1=app.THM;
            data2=[app.THM(:,1) a_resp];
            
            [fig_num]=...
                subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,...
                                                              data1,data2,t_string1,t_string2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.THM(:,1),rd_resp);
            out1=sprintf('Relative Displacement fn=%g Hz  Q=%g',fn,Q);
            title(out1);
            xlabel(' Time(sec) ')
            if(iu==1)
                ylabel('Rel Disp (in)')
            else
                ylabel('Rel Disp (mm)')    
            end
            grid on;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            name_a=app.AccelResponseArrayEditField.Value;
            name_rd=app.RelDispResponseArrayEditField.Value;
            name_trans=app.TransmissibilityArrayEditField.Value;
            
            assignin('base',name_a, [app.THM(:,1) a_resp]);
            assignin('base',name_rd, [app.THM(:,1) rd_resp]);   
         
            iss=strtrim(app.CalculateListBox.Value);
            
            if(strcmp(iss,'Time Histories Only'))
                ijk=1;
            end
            if(strcmp(iss,'Transmissibility'))
                ijk=2;
            end
            if(strcmp(iss,'Transmissibility & Phase'))
                ijk=3;
            end
                        
            if(ijk>=2)
             
                [f,trans,phase]=sine_sweep_transw(app.THM,a_resp,ijk,nnum);
                phase=-phase;
                
                figure(fig_num);
                fig_num=fig_num+1;
                
                plot(f,trans);
                title('Sine Sweep Transmissibility');
                xlabel('Frequency (Hz)');
                
                k=1;
                
                if( k>=1 )
                    ylabel('Trans (G/G)');
                else
                    ylabel('Trans');
                end    
                grid on;
                
                xl = xlim;
                fmin=xl(1);
                fmax=xl(2);
                
                trans=[f trans];
                assignin('base',name_trans,trans);


                if(ijk==3)
                    
                    t_string='Sine Sweep Magnitude & Phase';
                    
                    ff=f;
                    FRF_p=phase;
                    FRF_m=trans(:,2);
                    ylab='Trans (G/G)';
                    md=4;
                    
                    [fig_num]=...
                    plot_magnitude_phase_function_linear(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
                    
                end
                
                [fmax,amax]=find_max(trans);
                
                disp(' ');
                disp('* * * * * * * * * ');
                disp(' ');
                
                fprintf('\n  Maximum Transmissibility: (%8.4g Hz, %8.4g G/G) \n\n',amax,fmax);
                                         
            end
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_damped_sine
        function CalculateButton_damped_sinePushed(app, event)
            clear(app);

              amp=str2num(app.AmplitudeEditField_dsine.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField_dsine.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
             damp=str2num(app.DampingRatio1EditField.Value);
               td=str2num(app.DelayTimeEditField.Value);
               
            dt=1/sr;
           
            omegan=2*pi*freq;
            omegad=omegan*sqrt(1-damp^2);

        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    

            np=round((tmax+td)/dt);
            TT=linspace(0,(np-1)*dt,np);

        %
            a=zeros(length(TT),1);
            for i=1:length(TT)
                if(TT(i)>td)
                    a(i)=amp*sin(omegad*(TT(i)-td));
                    arg=-omegan*damp*(TT(i)-td);
                    a(i)=a(i)*exp(arg);
                end
            end            

            
            t_string='Damped Sine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];

            figure(fig_num);
            plot(app.THM(:,1),app.THM(:,2));
            xlabel('Time (sec)');
            ylabel(app.YaxisLabelEditField.Value);
            title(t_string);
            grid on;

            qq=get(gca,'ylim');
            qy=max(abs(qq));
            ylim([-qy qy]);

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.OutputNameEditField.Value='cosine';
            app.SaveButton.Visible='on';  
            app.CalculateFFTButton.Visible='on'; 

             app.OutputNameEditField.Value='damped_sine';

        end

        % Value changing function: FrequencyHzEditField_dsine
        function FrequencyHzEditField_dsineValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DampingRatio1EditField
        function DampingRatio1EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DelayTimeEditField
        function DelayTimeEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_two_correlated_white
        function CalculateButton_two_correlated_whitePushed(app, event)
            clear(app);

            YS=app.YaxisLabelEditField_white_2.Value;

            fig_num=1;
             
            tmax=str2num(app.DurationsecEditField_two_white.Value);    
            sigma1=str2num(app.StdDev1EditField.Value);   
            sigma2=str2num(app.StdDev2EditField.Value);   
            sr=str2num(app.SampleRateHzEditField_two_white.Value);
             
            dt=1./sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
          
        %%%    [a] = normrnd_function(sigma,np);
        
            clear length;
            np=length(TT);
            
            a=randn(np,1);
            a=fix_size(a);
            a=a-mean(a);
            a=a*sigma1/std(a);
            
            b=randn(np,1);
            b=fix_size(b);
            b=b-mean(b);

            b=b*sigma2/std(b);    
            
        %%%%%%
     
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);     
                [b,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase); 
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma1/stddev;
              a=(a-ave)*sss;        
                
              ave=mean(b);
              stddev=std(b);
              sss=sigma2/stddev;
              b=(b-ave)*sss;                  
            end    
            
            if(app.nf==3)
               t_string='White Noise';
            end     
   
        %%%%%%

            rho=str2num(app.CorrelationCoefficientEditField.Value);
            c=rho*a+sqrt(1-rho^2)*b;
            
            clear b;

            figure(fig_num);
            fig_num=fig_num+1;
            plot(TT,a,TT,c);
            title('White Noise');
            legend('First','Second');
            grid on;
            xlabel('Time (sec)');
            ylabel(YS);
            
            nnum=np;
            [xc,xmax,tmax]=cross_correlation_function(a,c,nnum,dt);
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %    
            [rho]=Pearson_coefficient(a,c);
        %
        
            disp(' ');
            disp(' Covariance Matrix ');
            disp(' ');
            
            cvm = cov(a,c);

            ss='Covariance Matrix';
            ss1=sprintf(' %7.3g \t  %7.3g ',cvm(1,1),cvm(1,2));
            ss2=sprintf(' %7.3g \t  %7.3g ',cvm(2,1),cvm(2,2));
            ss=sprintf('%s\n\n %s \n %s ',ss,ss1,ss2);
        %
        
            figure(fig_num);
            fig_num=fig_num+1;
            plot(a,c,'bo','MarkerSize',3);
            grid on;
            xlabel('First Signal Amplitude');
            ylabel('Second Signal Amplitude');
            out2=sprintf('Scatter Plot, Pearson Coefficient= %6.3g',rho);
            title(out2);
            axis square
            aa=max(abs(a));
            cc=max(abs(c));
            qmax=max([aa cc]);
            [ymax,~,~,iflag]=ytick_linear_double_sided(qmax);
            if(iflag==1)
                qmax=ymax;
            end
            xlim([-qmax qmax]);
            ylim([-qmax qmax]); 
           
        %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(xc(:,1),xc(:,2));
            xlabel(' Delay(sec) ');
            ylabel('Rxy');
            out1=sprintf(' Cross-correlation  Max Abs = %8.4g at %8.4g sec',xmax,tmax);
            title(out1);
            disp(out1);      
            grid on;
         
            xlabel2='Time (sec)';
            ylabel1=YS;
            ylabel2=YS;
            
            TT=fix_size(TT);
            a=fix_size(a);
            c=fix_size(c);
            
            data1=[TT a];
            data2=[TT c];
            data3=[TT a c];
            t_string1='First Signal';
            t_string2='Second Signal';
            
            nbars=31;                                      
                                              
            [fig_num]=plot_two_time_histories_histograms_alt(fig_num,xlabel2,...
                            ylabel1,ylabel2,data1,data2,t_string1,t_string2,nbars);    
                
            out1=sprintf('Pearson product-moment \ncorrelation coefficient = \n   %8.4g ',rho);
            ss=sprintf('%s \n\n%s',ss,out1); 
        
            prefix=app.OutputNameEditField_two_white.Value;    
            
            s1=sprintf('%s_1',prefix);
            s2=sprintf('%s_2',prefix);    
            s3=sprintf('%s_1_2',prefix);    
        
            assignin('base', s1,data1);   
            assignin('base', s2,data2);  
            assignin('base', s3,data3); 
            
            out1=sprintf(' Calculation Complete. Output arrays: \n  %s \n  %s \n  %s',s1,s2,s3);    
            msgbox(out1);
               
            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';  
        end

        % Button pushed function: CalculateButton_add_white_noise
        function CalculateButton_add_white_noisePushed(app, event)
            clear(app);

            try
                FS=app.SelectInputArrayListBox_add_white.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            sigma=str2num(app.StdDevEditField_add_white.Value);
         
            TT=app.THM(:,1);
            np=length(TT);

            dt=mean(diff(app.THM(:,1)));
            sr=1/dt;
            
            [a]=simple_white_noise(0,sigma,np);

            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %

              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                iphase=2;
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              a=(a-ave)*sss;        
                
            end    

       %%%%%

            app.THM(:,2)=app.THM(:,2)+a;

            fig_num=1;
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
            t_string='Composite Signal';
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            name=app.OutputNameEditField_add_white.Value;
            assignin('base',name,app.THM);

            app.SDOFResponsetoBaseInputAppliedForceButton.Visible='on';

            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';
              
        end

        % Button pushed function: CalculateButton_white_noise_basic
        function CalculateButton_white_noise_basicPushed(app, event)
            clear(app);

            sigma=str2num(app.StdDevEditField.Value);
            tmax=str2num(app.DurationsecEditField_white.Value);
            sr=str2num(app.SampleRateHzEditField_white.Value); 

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);
            
            [a]=simple_white_noise(0,sigma,np);
            
        %
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              a=(a-ave)*sss;        
                
            end    
            
            if(app.nf==3)
               t_string='White Noise';
            end     

       %%%%%

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField_white_2.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            name=app.OutputNameEditField_white.Value;
            assignin('base',name,app.THM);

            app.SDOFResponsetoBaseInputAppliedForceButton.Visible='on';

            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';
               
        end

        % Button pushed function: CalculateButton_white_noise_corrected
        function CalculateButton_white_noise_correctedPushed(app, event)
            clear(app);

            Items = char(app.RandomNumberSeedListBox_cor.Items);
            im=app.RandomNumberSeedListBox_cor.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            Items = char(app.SelectUnitsListBox.Items);
            im=app.SelectUnitsListBox.Value;
            [iunit]=find_index(Items,im);

            sigma=str2num(app.StdDevEditField_wc.Value);
            tmax=str2num(app.DurationsecEditField_wc.Value);
            sr=str2num(app.SampleRateHzEditField_wc.Value);
            
            npe=str2num(app.DurationTaperPercentEditField_cor.Value);           

            Items = char(app.T2_ListBox_cor.Items);
            im=app.T2_ListBox_cor.Value;
            [I2]=find_index(Items,im);

            Items = char(app.T3_ListBox_cor.Items);
            im=app.T3_ListBox_cor.Value;
            [I3]=find_index(Items,im);           

            %%%%%%

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np);
            tt=TT;
        
            clear length;
            np=length(TT);
            
            [a]=simple_white_noise(0,sigma,np);
            y=a;
            t_string='White Noise';
            
        %
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              y=(a-ave)*sss;        
                
            end    
            
            if(npe>0)
                [y]=half_cosine_fade_perc(y,npe);
            end

            a=y;
            
            v=dt*cumtrapz(a);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            tt=fix_size(tt);
            
            % In Between
            
            if(I2==2)
                v=v-mean(v);    
            end    
            if(I2==3)
                v=detrend(v);        
            end 
            if(I2==4) 
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end 
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            if(npe>0)
                [v]=half_cosine_fade_perc(v,npe);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2

            d=dt*cumtrapz(v);
            
            if(I3==2)
                d=d-mean(d);    
            end    
            if(I3==3)
                d=detrend(d);        
            end 
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end 
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end   
            
            if(npe>0)
                [d]=half_cosine_fade_perc(d,npe);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (ips)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/s^2)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            [vv]=differentiate_function(d,dt);
            [aa]=differentiate_function(vv,dt);
            
            if(iunit==1) % G, in/sec, in
                aa=aa/386;
            end
            if(iunit==2) % G, cm/sec, mm
                vv=vv/10;
                aa=aa/(9.81*1000);
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vv=vv/10;
                aa=aa/1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            vvv=dt*cumtrapz(aa);
            ddd=dt*cumtrapz(vvv);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iunit==1) % G, in/sec, in
                vvv=vvv*386;
                ddd=ddd*386;
            end
            if(iunit==2) % G, cm/sec, mm
                vvv=vvv*9.81*100;
                ddd=ddd*9.81*1000;
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vvv=vvv*10;
                ddd=ddd*1000;
            end

            tt=fix_size(tt);
            aa=fix_size(aa);
            vvv=fix_size(vvv);
            ddd=fix_size(ddd);


            app.accel=[tt aa];
            app.vel=[tt vvv];
            app.dispx=[tt ddd];            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fig_num=1;
            
            tstart=tt(1);
            tend=1.001*tt(np);
            
            hp=figure(fig_num);
            fig_num=fig_num+1;
            subplot(3,3,1);
            plot(tt,a);
            ylabel(ay);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,2);
            plot(tt,aa);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,3);
            plot(tt,aa);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,4);
            plot(tt,v);
            ylabel(vy);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,5);
            plot(tt,vv);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,6);
            plot(tt,vvv);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,7);
            plot(tt,d);
            xlim([tstart tend]);
            ylabel(dy);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,8);
            plot(tt,d);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,9);
            plot(tt,ddd);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            set(hp, 'Position', [0 0 850 650]);

            %%%%%%

            fig_num=2;
            app.THM=[tt aa];
            x_label='Time (sec)';
            y_label=ay;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(aa);
            crest=max(abs(aa))/sd;
    
            s1=sprintf(' n=%d \n\n',length(aa));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(aa),min(aa));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';

        end

        % Button pushed function: SDOFResponsetoBaseInputAppliedForceButton
        function SDOFResponsetoBaseInputAppliedForceButtonPushed(app, event)
            sdof_response_time_domain; 
        end

        % Value changed function: BandlimitviaFilteringListBox
        function BandlimitviaFilteringListBoxValueChanged(app, event)
            ChangeFilter(app);
            clear(app);
        end

        % Value changing function: StdDevEditField
        function StdDevEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_white
        function DurationsecEditField_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_white
        function SampleRateHzEditField_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changed function: RandomNumberSeedListBox
        function RandomNumberSeedListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: LowpassFreqHzEditField
        function LowpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: HighpassFreqHzEditField
        function HighpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_sk
        function CalculateButton_skPushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateEditField_sk.Value);
            tmax=str2num(app.DurationsecEditField_sk.Value);
            YS=app.YaxisLabelEditField_sk.Value;

            Items = char(app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Items);
            im=app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Value;
            [app.nf]=find_index(Items,im);
            
            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            Items = char(app.SkewnessListBox.Items);
            im=app.SkewnessListBox.Value;
            [n]=find_index(Items,im);

            sigma=str2num(app.StdDevEditField_sk.Value);
            skgoal = str2num(app.SkewnessListBox.Value);
            ktgoal = str2num(app.KurtosisEditField.Value);       

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);

            %%%%%

            app.num=np;

            skerror=1.0e+20;
            kterror=skerror;

            x=zeros(app.num,1);
            
            for i=1:app.num      
                x(i)=(i-1)*dt;
            end
            
            if(app.nf==1)
            %
            %    iband:   1=lowpass  2=highpass  3=bandpass 
            %
            %    iphase=1  for refiltering for phase correction
            %          =2  for no refiltering
                  iband=1;
                  fl=sr/10;
                  fh=0;
                  iphase=2;
            end    
            
            for nt=1:7
            
                a=randn(app.num,1);
                y=zeros(app.num,1);
            
                if(app.nf==1)
            %    
                  [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);
            %      
                end      
               
                if(skgoal==0)  % skew = 0
                    ex = 0.842*(ktgoal-1.36)^0.378;
            %
                    for i=1:app.num
                        y(i)=sign(a(i))*(abs(a(i))^ex);
                    end
                else
                    [p1,p2,skgoal]=skewness_kurtosis_coefficients(n,app.nf);       
                end

                if(skgoal>0)  % skew >= 0.1
                    LK=log10(ktgoal);
                    exp= p1(1)*LK^2+p1(2)*LK+p1(3);
                    exn= p2(1)*LK^2+p2(2)*LK+p2(3);
                
                    for i=1:app.num
                     
                        if(a(i)>0)
                            y(i)=a(i)^exp;
                        else
                            y(i)=-(abs(a(i))^exn);            
                        end    
                    end 
                end    
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                sd=std(y);
                mu=mean(y);
            
                y=y-mu;
            
                y=y*sigma/sd;
            
                [mu,sd,~,sk,kt]=kurtosis_stats(y);
                
                if( abs(sk-skgoal)<skerror && abs(kt-ktgoal)<kterror)
                    skerror=abs(sk-skgoal);
                    kterror=abs(kt-ktgoal);
                    
                    ybest=y;
                    
                    mu_best=mu;
                    sd_best=sd;  
                    sk_best=sk;
                    kt_best=kt;
                end
            
            end   

            %%%%%

            a=ybest;

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            t_string=sprintf(' mean=%4.2f  std=%g  ',mu_best,sd_best);
            x_label='Time (sec)';
            y_label=YS;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_random_sk.Value=ss;

            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';

            name=app.OutputNameEditField_sk.Value;

            try
                assignin('base',name,app.THM);
                update_list(app);
            catch
                warndlg('save failed');
            end            
        end

        % Button pushed function: CalculateButton_Sarkani
        function CalculateButton_SarkaniPushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateEditField_sk.Value);
            duration=str2num(app.DurationsecEditField_sk.Value);
            y_label=app.YaxisLabelEditField_sk.Value;
            sigma=str2num(app.StdDevEditField_sk.Value);

            Items = char(app.SpecificationMethodListBox.Items);
            im=app.SpecificationMethodListBox.Value;
            [nmeth]=find_index(Items,im);

            if(nmeth==1)
                kt=str2num(app.kurtosisEditField.Value);  
                [beta,n,ktr]=Sarkani_table(kt);
            else
                beta=str2num(app.betaEditField.Value);
                n=str2num(app.nEditField.Value);
            end
            
            dt=1/sr;
            
            nt=floor(duration/dt);
            
            t=zeros(nt,1);
            for i=1:nt
               t(i)=(i-1)*dt; 
            end
            
            mu=0;
            
            [X]=simple_white_noise(mu,sigma,nt);
           
            Items = char(app.BandlimitviaLowpassFilterListBox_Sarkani.Items);
            im=app.BandlimitviaLowpassFilterListBox_Sarkani.Value;
            [nff]=find_index(Items,im);
            
            if(nff==1)
                fl=sr/10;
                fh=0;
                iband=1;
                iphase=2;
                [X,~,~,~]=Butterworth_filter_function_alt(X,dt,iband,fl,fh,iphase);    
            end
            
            Y=zeros(nt,1);
            for i=1:nt
                Y(i)=X(i)+beta*sign(X(i))*(abs(X(i))^n);
            end
            %        
            Y=sigma*Y/std(Y);
         
            Y=fix_size(Y);
            t=fix_size(t);
                        
            app.signal=[t Y];

            fig_num=1;
            app.THM=app.signal;
            x_label='Time (sec)';

            nbars=31;
        
            t_string=sprintf('Random Time History');
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);

            a=Y;
            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_random_sk.Value=ss;

            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';

            name=app.OutputNameEditField_Sarkani.Value;

            try
                assignin('base',name,app.THM);
            catch
                warndlg('save failed');
            end
        end

        % Button pushed function: CalculateButton_pink
        function CalculateButton_pinkPushed(app, event)
            
            % does not use Audio toolbox
            
            clear(app);

            Items = strtrim(app.LapaceTransformListBox.Items);
            im = strtrim(app.LapaceTransformListBox.Value);
            LA = find(strcmp(Items, im));

            sr=str2num(app.SampleRateEditField_sk.Value);
            tmax=str2num(app.DurationsecEditField_sk.Value);
            YS=app.YaxisLabelEditField_sk.Value;
            sigma=str2num(app.StdDevEditField_sk.Value);

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np);       
            np=length(TT);

            if(LA==1)
                PP = pinknoise(np, 1);
            else
                sr = 192.0e+03;   % pre-set sample rate
                app.SampleRateEditField_sk.Value=sprintf('%8.4g',sr);
                dt=1/sr; 
                np=ceil(tmax/dt);

                r = mod(np,2);
                if(r~=0)
                    np=np-1;
                end    
                TT=linspace(0,(np-1)*dt,np);   

                %
                t=TT;
                %
                %  generate white noise time history
                %
                [white]=simple_white_noise(0,sigma,np);
                %
                white_FFT=fft(white);
                %
                epi=8*pi;
                tpi=2*pi;
                %
                df=1/(np*dt);
                %
                nh=np/2;
                %
                H=zeros(nh,1);
                %
                disp(' transfer function ');
                disp(' ');
                %
                for i=1:nh
                    s=(1i)*(i-1)*tpi*df;   
                    H(i)=3/sqrt(s+epi);
                end
                %
                nff=nh;
                    frf=zeros(2*nff,1);
                    frf(1:nff)=H(1:nff);
                %
                    for i=1:nff
                        frf(i+nff)=(i+nff)*df;
                    end
                %
                    aa=H;
                    bb= flipud(aa);
                %
                    for i=1:nff
                        frf(i+nff)=real(bb(i))-(1i)*imag(bb(i));
                    end
                %
                    frf_amp=flipud(frf);
                %
                A=zeros(np,1);
                for i=1:np
                    A(i)=frf_amp(i)*white_FFT(i);
                end
                %
                disp(' inverse FFT ');
                disp(' ');
                %
                pink=real(ifft(A,np,'symmetric'));   
                %
                PP=pink(1:np);
                TT=t(1:np);
                TT=fix_size(TT);
                PP=fix_size(PP);
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                iphase=2;
                f=24e+03;
                %
                disp(' Lowpass filter ');
                disp(' ');
                [PP]=Butterworth_LPF(PP,dt,f,iphase);              
            end    
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %  fade in & out
        %
            na=floor(0.01*tmax/dt);
            nb=np-na;
            delta=np-nb;

            for i=1:na
                arg=pi*(( (i-1)/(na-1) )+1); 
                PP(i)=PP(i)*0.5*(1+(cos(arg)));
            end
        %
            for i=nb:np
                arg=pi*( (i-nb)/delta );
                PP(i)=PP(i)*(1+cos(arg))*0.5;
            end
        %
            TT=fix_size(TT);
            PP=fix_size(PP);
        %

            PP=PP-mean(PP);
            PP=PP*sigma/std(PP); 

            pink_time_history=[TT PP];

            name=app.OutputNameEditField_pink.Value;
            assignin('base',name,pink_time_history);

            figure(1);
            plot(TT,PP);
            title('Pink Noise Time History');
            xlabel('Time (sec)');
            ylabel(YS);
            grid on;

            [mu,sd,rms,sk,kt]=kurtosis_stats(PP);

            crest=max(abs(PP))/sd;
    
            s1=sprintf(' n=%d \n\n',length(PP));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(PP),min(PP));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);
     
            app.ResultsTextArea_random_sk.Value=ss;
            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';   

            axx=app.AudioOutputNameEditField.Value;
            audiowrite(axx,rescale(PP),sr);
            app.afilename=axx;
         
        end

        % Value changed function: SpecificationMethodListBox
        function SpecificationMethodListBoxValueChanged(app, event)
            Sarkani(app);
            clear(app);
        end

        % Callback function
        function SDOFResponsetoBaseInputButton_2Pushed(app, event)
            sdof_response_time_domain;
        end

        % Value changing function: SampleRateEditField_sk
        function SampleRateEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_sk
        function DurationsecEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField_sk
        function StdDevEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changed function: RandomNumberSeedListBox_2
        function RandomNumberSeedListBox_2ValueChanged(app, event)
            clear(app);
        end

        % Value changing function: KurtosisEditField
        function KurtosisEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: 
        % BandlimitviaLowpassFilterListBox_Sarkani_sk
        function BandlimitviaLowpassFilterListBox_Sarkani_skValueChanged(app, event)
            clear(app);
        end

        % Value changing function: betaEditField
        function betaEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: BandlimitviaLowpassFilterListBox_Sarkani
        function BandlimitviaLowpassFilterListBox_SarkaniValueChanged(app, event)
            clear(app);
        end

        % Value changing function: kurtosisEditField
        function kurtosisEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: nEditField
        function nEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: SkewnessListBox
        function SkewnessListBoxValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_add_white(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethod]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox_add_white.Value=name;
            end    
        end

        % Value changing function: DurationsecEditField_two_white
        function DurationsecEditField_two_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_two_white
        function SampleRateHzEditField_two_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDev1EditField
        function StdDev1EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDev2EditField
        function StdDev2EditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_unit_impulse
        function CalculateButton_unit_impulsePushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateHzEditField_unit_impulse.Value);
            dur=str2num(app.DurationsecEditField_unit_impulse.Value);

            dt=1/sr;   
            
            np=floor(dur/dt);
            
            TT=linspace(0,np*dt,(np+1)); 
            a = zeros(1,(np+1));
            
            a(2)=1/dt;
        
            TT=fix_size(TT);
            a=fix_size(a);    

            app.THM=[TT a];

            tstring='Unit Impulse, Area=1';

            num_a=length(a);  

            ylab=app.YaxisLabelEditField_unit_impulse.Value;

            figure(1);
            plot(TT(1:num_a),a);
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(ylab);

            try
                name=app.OutputArrayNameEditField_unit_impulse.Value;
                name
            catch
                warndlg('name failed');
                return;
            end

            try
                assignin('base',name,app.THM);
                msgbox('Array saved');
            catch
                warndlg('Save failed');
            end

        end

        % Value changing function: DurationsecEditField_unit_impulse
        function DurationsecEditField_unit_impulseValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_unit_impulse
        function SampleRateHzEditField_unit_impulseValueChanging(app, event)
            clear(app);
        end

        % Value changed function: PulseTypeListBox
        function PulseTypeListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: AmplitudeEditField_halfsine
        function AmplitudeEditField_halfsineValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_halfsine
        function SampleRateHzEditField_halfsineValueChanging(app, event)
            clear(app);
        end

        % Value changed function: PrepulseDurationsecEditField
        function PrepulseDurationsecEditFieldValueChanged(app, event)
            clear(app);
        end

        % Value changing function: PulseDurationsecEditField
        function PulseDurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PostPulseDurationsecEditField
        function PostPulseDurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PrepulseDurationsecEditField
        function PrepulseDurationsecEditFieldValueChanging(app, event)
            clear(app);
            
        end

        % Button pushed function: CalculateButton_halfsine
        function CalculateButton_halfsinePushed(app, event)
            clear(app);

            Items = char(app.PulseTypeListBox.Items);
            im=app.PulseTypeListBox.Value;
            [n]=find_index(Items,im);

            YS=app.YaxisLabelEditField_halfsine.Value;

            amp=str2num(app.AmplitudeEditField_halfsine.Value);
            sr=str2num(app.SampleRateHzEditField_halfsine.Value);
            t1=str2num(app.PrepulseDurationsecEditField.Value);
            t2=str2num(app.PulseDurationsecEditField.Value);
            t3=str2num(app.PostPulseDurationsecEditField.Value);

            dt=1/sr;
            t12=t1+t2;   
            tmax=t1+t2+t3;
            np=round(tmax/dt);
            TT=linspace(0,np*dt,(np+1)); 
            a = zeros(1,(np+1)); 

            if(n==1)
                tstring='Half-Sine Pulse';
  
                for i=1:np
                    t=TT(i);
                    if(t>=t1 && t<=t12)
                        a(i) = sin(pi*(t-t1)/t2);
                    end
                    if(t>=t12)
                        break;
                    end
                end
                a=a*amp;

            end    
            if(n==2)
                tstring='Versed Sine Pulse';

                for i=1:np
                    t=TT(i);
                    if(t>=t1 && t<=t12)
                        a(i) = 1-cos(2*pi*(t-t1)/t2);
                    end
                    if(t>=t12)
                        break;
                    end
                end
                a=a*amp/2;    

            end    
            if(n==3)
                tstring='Terminal Sawtooth Pulse';
  
                for i=1:np
                    t=TT(i);
                    if(t>=t1 && t<=t12)
                        a(i) = (t-t1)/t2;
                    end
                    if(t>=t12)
                        break;
                    end
                end
                a=a*amp;

            end               
            if(n==4)
                tstring='Symmetric Sawtooth Pulse';

                t12=t1+t2;   
                t2half=t2/2;
                t12h=t1+t2half;
               
                for i=1:np
                    t=TT(i);
                    if(t>=t1 && t<t12h)
                        a(i) = (t-t1)/t2half;
                    end
                    if(t>=t12h && t<t12)
                        a(i) = 1-((t-t12h)/t2half);
                    end 
                    if(t>=t12)
                        break;
                    end
                end
                a=a*amp;
            end   
            if(n==5)
                tstring='Rectangular Pulse';
  
                for i=1:np
                    t=TT(i);
                    if(t>=t1 && t<=t12)
                        a(i)=1;
                    end
                    if(t>=t12)
                        break;
                    end
                end
                a=a*amp;
            end 

            t=fix_size(TT);
            a=fix_size(a);

            figure(1);
            plot(t,a);
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(YS);
            
            %%%

            name=app.OutputArrayNameEditField_halfsine.Value;

            p=[t a];

            try
                assignin('base',name,p);
            catch
                warndlg('Save array');
            end

        end

        % Value changing function: AmplitudeEditField_wavelet
        function AmplitudeEditField_waveletValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FreqHzEditField
        function FreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DelayTimesecEditField
        function DelayTimesecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: EndTimesecEditField
        function EndTimesecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_wavelet
        function SampleRateHzEditField_waveletValueChanging(app, event)
             clear(app);
        end

        % Value changing function: YaxisLabelEditField_wavelet
        function YaxisLabelEditField_waveletValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_wavelet
        function CalculateButton_waveletPushed(app, event)
            clear(app);
            
            app.OutputArrayNameEditField_wavelet

            YS=app.YaxisLabelEditField_wavelet.Value;
            
            N=str2num(app.NumberofHalfSinesListBox.Value);
            
            t2=str2num(app.EndTimesecEditField.Value);
            sr=str2num(app.SampleRateHzEditField_wavelet.Value);
            td=str2num(app.DelayTimesecEditField.Value);
            amp=str2num(app.AmplitudeEditField_wavelet.Value);
            freq=str2num(app.FreqHzEditField.Value);

            dt=1./sr;
        %
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               out1=sprintf('%8.4g',sr);
               app.SampleRateHzEditField_wavelet.Value=out1;
            end   
        %
            tmax=N/(2*freq)+td;
            if(tmax>t2)
                t2=dt+tmax;
            end
            t1=0;
            np=ceil((t2-t1)/dt);
            TT=linspace(0,np*dt,(np+1)); 
            TT=TT+t1;   
            beta= (2*pi*freq);
            alpha= beta/N;    
        %
            np=max(size(TT));

            a=zeros(np,1);
            v=zeros(np,1);
            d=zeros(np,1);

            for i=1:np
                t=TT(i);
                if(t>=td && t<= tmax)
                    a(i) = sin(pi*t/tmax);
                    apb=alpha+beta;
                    amb=alpha-beta;
                    ttd=t-td;
        %            
                    at=alpha*(ttd);
                    bt= beta*(ttd);
        %
                    a(i)= sin(at)*sin(bt);
                    v(i)=-(sin(apb*ttd)/apb) + (sin(amb*ttd)/amb);
                    d(i)=+((cos(apb*ttd)-1)/apb^2)-((cos(amb*ttd)-1)/amb^2);
                end
            end
            a= a*amp;
            v= v*amp/2;
            d= d*amp/2;    
        %
            tstring='Wavelet';

            t=fix_size(TT);
            a=fix_size(a);

            figure(1);
            plot(t,a);
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(YS);
            
            %%%

            name=app.OutputArrayNameEditField_wavelet.Value;
            t=fix_size(t);

            p=[t a];

            try
                assignin('base',name,p);
            catch
                warndlg('Save array');
            end

        end

        % Button down function: WhiteCorrectedTab
        function WhiteCorrectedTabButtonDown(app, event)
            app.BandlimitviaFilteringListBox.Value=app.BandlimitviaFilteringListBox.Items{2};
            ChangeFilter(app);
        end

        % Button pushed function: CalculateButton_IEEE
        function CalculateButton_IEEEPushed(app, event)
            clear(app);
            
            Items = char(app.SelectUnitListBox.Items);
            im=app.SelectUnitListBox.Value;
            [iunit]=find_index(Items,im);

            n=app.nIEEE;

            if(n==1) % High PL
                A=0.6;
            end
            if(n==2) % High
                A=0.5;
            end
            if(n==3) % Moderate PL
                A=0.3;
            end 
            if(n==4) % Moderate
                A=0.25;
            end    
            if(n==5) % other
                A=str2num(app.AccelerationGEditField.Value);
            end
            
             f=str2num(app.FrequencyHzEditField_IEEE.Value);
            nc=str2num(app.NumberofCyclesEditField.Value);
             p=str2num(app.PausesecEditField.Value);
            nb=str2num(app.NumberofBeatsEditField.Value);
            preshock=str2num(app.PreshockDurationsecEditField.Value);
            
            sr=128*f;
            dt=1/sr;
            
            T=1/f;
            
            Tnc=nc*T;
            TncP=Tnc+p;
            
            fm=1/(2*Tnc);
            
            rho=f/fm;
            
            nt=ceil(TncP/dt);
            
            k=ceil(preshock/dt);
            
            nnn=k+nb*nt;
            
            t=zeros(nnn,1);
            a=zeros(nnn,1);
            
            for i=1:nnn
                t(i)=(i-1)*dt;
            end
            
            om1=2*pi*f;
            om2=om1/rho;
            
            k=ceil(preshock/dt);
            
            for i=1:nb
                for j=1:nt
                    tl=(j-1)*dt;
                    if(tl<=Tnc)
                        a(k)=sin(om1*tl)*sin(om2*tl);
                    end
                    k=k+1;
                end
            end
            
            a=a*A;
            
            t=fix_size(t);
            ha=fix_size(a);
            va=0.8*ha;
            
            [hv]=integrate_function(ha,dt);
            [hd]=integrate_function(hv,dt);
            
            [vv]=integrate_function(va,dt);
            [vd]=integrate_function(vv,dt);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iunit==1)
                hv=hv*386;
                hd=hd*386;
                vv=vv*386;
                vd=vd*386;    
                ay='Accel (G)';
                vy='Vel (in/sec)';
                dy='Disp (in)';
            end
            if(iunit==2)
                hv=hv*9.81*100;
                hd=hd*9.81*1000;    
                vv=vv*9.81*100;
                vd=vd*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/sec)';
                dy='Disp (mm)';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(1);
            
            subplot(3,1,1);
            plot(t,ha);
            ylabel(ay);
            grid on;
            out1=sprintf('IEEE std 693 Sine Beat  Horizontal  freq=%g Hz',f);
            title(out1);
            yLimits = get(gca,'YLim');
            ya=max(abs(yLimits));
            ylim([-ya,ya]);
             
            subplot(3,1,2);
            plot(t,hv);
            ylabel(vy);
            grid on;
            yLimits = get(gca,'YLim');
            yv=max(abs(yLimits));
            ylim([-yv,yv]);
             
            subplot(3,1,3);
            plot(t,hd);
            ylabel(dy);
            xlabel('Time(sec)');
            grid on;
            yLimits = get(gca,'YLim');
            yd=max(abs(yLimits));
            ylim([-yd,yd]);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(2);
            
            subplot(3,1,1);
            plot(t,va);
            ylabel(ay);
            grid on;
            out1=sprintf('IEEE std 693 Sine Beat  Vertical  freq=%g Hz',f);
            title(out1);
            ylim([-ya,ya]);
             
            subplot(3,1,2);
            plot(t,vv);
            ylabel(vy);
            grid on;
            ylim([-yv,yv]);
            
            subplot(3,1,3);
            plot(t,vd);
            ylabel(dy);
            xlabel('Time(sec)');
            grid on;
            ylim([-yd,yd]);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            out7=sprintf(' Frequency = %8.4g Hz',f);
            disp(out7);
            disp(' ');
            disp(' Maximum Absolute Values for Horizontal Axis ');
            disp('  ');
            
            out1=sprintf(' %s %8.4g  ',ay,max(abs(ha)));
            out2=sprintf(' %s %8.4g  ',vy,max(abs(hv)));
            out3=sprintf(' %s %8.4g  ',dy,max(abs(hd)));
            
            disp(out1);
            disp(out2);
            disp(out3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hname='sine_beat_horizontal';
            vname='sine_beat_vertical';
            
            assignin('base', hname, [t ha]);
            assignin('base', vname, [t ha]);
            
            message = sprintf('\n Output Time History Arrays: time(sec) & accel(G) \n\n  %s\n  %s',hname,vname);
            uiwait(msgbox(message,'Calculation Complete'));
            
        end

        % Value changed function: AccelerationListBox
        function AccelerationListBoxValueChanged(app, event)
          
            IEEE(app);
        end

        % Button pushed function: CalculateButton_student
        function CalculateButton_studentPushed(app, event)
            clear(app);

            YS=app.AmplitudeLabelEditField.Value;
            
            if(app.nStudent==1)
                v=str2num(app.Enterdofrealnumber4EditField.Value);
                
                if(v<=4)
                    warndlg(' dof must be > 4');
                    return;
                end
            
                k=(3*v-6)/(v-4);
                ssdk=sprintf('%6.2g',k);
            else
                k=str2num(app.Enterkurtosisrealnumber3EditField.Value);
                
                if(k<=3)
                    warndlg(' kurtosis must be > 3');
                    return;
                end
                
                v=(4*k-6)/(k-3);
                ssdf=sprintf('%6.2g',v); 
            end
            
            stda=str2num(app.StdDevEditField_student.Value);
            dur=str2num(app.DurationsecEditField_student.Value);
            sr=str2num(app.SampleRateHzEditField_student.Value);
            
            dt=1/sr;
            
            nt=floor(dur*sr);
            
            a=-2/v;
            
            t=zeros(nt,1);
            x=zeros(nt,1);
            
            best=zeros(nt,1);
            
            record=1.0e+90;
            
            M=10;
            
            progressbar;
            
            for j=1:M
                progressbar(j/M);
            
                for i=1:nt
            %
                    t(i)=(i-1)*dt;
            %    
                    while(1)
                        U=(2*rand-1);
                        V=(2*rand-1);
                        w=U^2+V^2;
                        if(w<1 && abs(w)>1.0e-25)
                            break;
                        end
                    end
            %
                    x(i)=U*sqrt(v*(w^a-1)/w);
            %
                end
            %    
                x=stda*x/(std(x));
            %
                mu=mean(x);
                sd=std(x);
            
                ks=0;
            
                for i=1:nt
                    ks=ks+(x(i)-mu)^4;        
                end    
                ks=ks/(nt*sd^4);    
            %
                error = abs(1-(ks/k));
            %
                if(error<record)
                    record=error;
                    best=x;
                    
                    if(record<0.03)
                        break;
                    end             
                end    
            %
            end
            pause(0.4);
            progressbar(1);
            
            x=best;    
            
            st=[t x];
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [mu,sd,rms,sk,kt]=kurtosis_stats(x);
             
            string_x=sprintf('\n Amplitude Stats ');
            string2=sprintf('\n\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=string_x;
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            
            mx=max(x);
            mi=min(x);
            
            amax=abs(mx);
            amin=abs(mi);
            
            if(amax<amin)
               amax=amin;
            end
                
            crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);    
               
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
                         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            t_string='Student t Time History';
            y_label=YS;
            x_label='Time (sec)';
            app.THM=st;
            nbars=61;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            name=app.OutputArrayNameEditField_student.Value;

            assignin('base',name,app.THM);

            app.ResultsTextArea_student.Value=big_string;
            app.ResultsTextArea_student.Visible='on';
            app.ResultsTextAreaLabel_student.Visible='on';
        end

        % Value changing function: StdDevEditField_student
        function StdDevEditField_studentValueChanging(app, event)
          
            clear(app);

            if(app.nStudent==1)
                app.Enterdofrealnumber4EditField.Visible='on';
                app.Enterdofrealnumber4Label.Visible='on';  
            else
                app.Enterkurtosisrealnumber3EditField.Visible='on';
                app.Enterkurtosisrealnumber3EditFieldLabel.Visible='on';
            end            

        end

        % Value changing function: DurationsecEditField_student
        function DurationsecEditField_studentValueChanging(app, event)
            clear(app);

        end

        % Value changing function: SampleRateHzEditField_student
        function SampleRateHzEditField_studentValueChanging(app, event)
            clear(app);

        end

        % Value changing function: Enterdofrealnumber4EditField
        function Enterdofrealnumber4EditFieldValueChanging(app, event)
        
            clear(app);

        end

        % Callback function
        function ExpectedKurtosisEditFieldValueChanging(app, event)
            clear(app);

        end

        % Value changed function: MethodListBox
        function MethodListBoxValueChanged(app, event)

            student_method(app);

        end

        % Button pushed function: CalculateButton_multiple_sine
        function CalculateButton_multiple_sinePushed(app, event)
          
            T=str2num(app.DurationsecEditField_msweep.Value);
            SR=str2num(app.SampleRateHzEditField_msweep.Value);
            name=app.OutputTimeHistoryNameEditField.Value;

            Items = char(app.RateListBox.Items);
            im=app.RateListBox.Value;
            [app.ntype]=find_index(Items,im);           

            N=app.n_msweep;
          
            dur=T;
            dt=1/SR;
            
            NT=round(T/dt);
            ntimes=NT;
            
            app.THM=zeros(NT,2);
            
            TT=linspace(0,ntimes*dt,ntimes); 
            app.THM(:,1)=TT;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            data=app.UITable_msweep.Data;

            A=char(data);
               
            fstart=zeros(N,1);
            fend=zeros(N,1);
            amp=zeros(N,1);
            
            try
                k=1;

                for i=1:N
                    k=k+1;
                end    

                for i=1:N
                    a=A(k,:); k=k+1;
                    fstart(i,1) = str2double(strtrim(a));
                end    
                
                for i=1:N
                    a=A(k,:); k=k+1;
                    fend(i,1) = str2double(strtrim(a));
                end   
                   
                for i=1:N
                    a=A(k,:); k=k+1;
                    amp(i,1) = str2double(strtrim(a));
                end   
                
            catch
                warndlg('Input Arrays read failed');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            tpi=2*pi;  
            
            for ijk=1:N
            
                f1=fstart(ijk);
                f2=fend(ijk);
                
                oct=log(f2/f1)/log(2);
            
                if(app.ntype==1)  % linear
                    rate=(f2-f1)/dur;
                else          % log
                    rate=oct/dur;
                end
               
                 arg = zeros(1,ntimes);
                freq = zeros(1,ntimes);    
                
                if(app.ntype==1)  % linear
                %
                % 0.5 factor is necessary to obtain correct number of cycles for linear case.
                %
                %			fspectral= (     rate*t ) + f1;
                    fmax=0.5*(f2-f1)+f1;
                    freq=linspace(f1,fmax,NT);     
                %
                else  % log
                %  
                %			fspectral = f1*pow(2.,rate*t);
                %
                    for i=1:ntimes
                        arg(i)=-1.+2^(rate*TT(i));
                    end   
                end    
                
                freq=fix_size(freq);
                TT=fix_size(TT);
            
                if(app.ntype==1) % linear
                    arg=tpi*freq.*TT(1:NT);
                else  %log
                    arg=tpi*f1*arg/(rate*log(2));  
                end    
                
                aa=amp(ijk)*sin(arg);
                aa=fix_size(aa);
                app.THM(:,2)=app.THM(:,2)+aa;
                
            end
            
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            ylabel('Accel (G)');
            xlabel('Time (sec)');
            grid on;
            
            assignin('base',name,[app.THM(:,1),app.THM(:,2)]);

        end

        % Button pushed function: UpdateListButton_add_sine
        function UpdateListButton_add_sinePushed(app, event)
            update_add_sine(app);
        end

        % Value changed function: listbox_method_add_sine
        function listbox_method_add_sineValueChanged(app, event)
      
            Items = char(app.listbox_method_add_sine.Items);
            im=app.listbox_method_add_sine.Value;
            [app.nMethod]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox_add_sine.Value=name;
            end    
        end

        % Button pushed function: CalculateButton_add_sine
        function CalculateButton_add_sinePushed(app, event)
            clear(app);

            try
                FS=app.SelectInputArrayListBox_add_sine.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            Items = char(app.SelectUnitListBox_add_sine.Items);
            im=app.SelectUnitListBox_add_sine.Value;
            [iunit]=find_index(Items,im);

            %%%%
               
            N=app.n_add_sine;
                
            A=char(app.UITable_add_sine.Data);    
            B=str2num(A);
                
            peak=B(1:N);
            freq=B((N+1):(2*N)); 
            phase=B((2*N+1):(3*N));     
            phase=phase*pi/180;
                
            omega=2.*pi*freq;
                
            TT=app.THM(:,1);
            np=length(TT);
               
            a = zeros(1,np);  
                
            for i=1:N
                for j=1:np
                    a(j)=a(j)+peak(i)*sin(omega(i)*TT(j)-phase(i));
                end    
            end  
            
            kvn=1;
            fstart=1;
               
            dt=mean(diff(TT));
               
            TT=fix_size(TT);
            a=fix_size(a);    
            a=a+app.THM(:,2);
               
            Items = char(app.VelocityDisplacementCorrectionListBox.Items);
            im=app.VelocityDisplacementCorrectionListBox.Value;
            [nc]=find_index(Items,im);

            Items = char(app.AmplitudeTypeListBox.Items);
            im=app.AmplitudeTypeListBox.Value;
            [amp_type]=find_index(Items,im);
                                
            if(nc==1 && amp_type==1)
                fc=str2num(app.HighPassFreqHzEditField.Value);
                a=detrend(a);      
                [a]=Butterworth_filter_highpass_function(a,fc,dt);  

                fper1=1/100;
                [a,velox,~]=velox_correction_alt(a,dt,kvn,fstart,iunit,fper1);
                    
            end    
                
            a=fix_size(a);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);
                
            out1=sprintf('     max=%8.4g \n',max(a));
            out2=sprintf('     min=%8.4g \n\n',min(a));   
            out3=sprintf('    mean=%8.4g \n\n',mu);          
            out4=sprintf(' std dev=%8.4g \n',sd);    
            out5=sprintf('     rms=%8.4g \n',rms);    

            ss=sprintf('%s %s %s %s %s',out1,out2,out3,out4,out5);
            app.ResultsTextArea_add_sine.Value=ss;
            app.ResultsTextArea_add_sine.Visible='on';
            app.ResultsTextAreaLabel_add_sine.Visible='on';

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                 
            
            
            if(amp_type==1)
                t_string=sprintf('Acceleration Combined Signal  %8.4g GRMS',rms);
            else
                t_string=sprintf('Combined Signal  %8.4g RMS',rms);
            end

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YSEditField_add_Sine.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            if(amp_type==1)
                
                aat='Acceleration';
                vvt='Velocity';
                ddt='Displacement';
                ay='Accel (G)';

                if(iunit==1)
                    vy='Vel (in/sec)';
                    dy='Disp (in)';
                else
                    vy='Vel (cm/sec)';
                    dy='Disp (mm)';                    
                end    

                tt=TT;
                v=velox;

                app.dispx=integrate_function(v,dt);
                         
                app.dispx=fix_size(app.dispx);
                d=app.dispx;

                [fig_num,hp]=plot_avd_time_histories_subplots_altp_titles(fig_num,tt,a,v,d,ay,vy,dy,aat,vvt,ddt);

            end    

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            name=app.OutputArrayNameEditField_add_sine.Value;
            assignin('base',name,[TT a]);


        end

        % Value changed function: AmplitudeTypeListBox
        function AmplitudeTypeListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: VelocityDisplacementCorrectionListBox
        function VelocityDisplacementCorrectionListBoxValueChanged(app, event)
            clear(app);
            vcorrect(app);
        end

        % Value changed function: NumberofSinesListBox
        function NumberofSinesListBoxValueChanged(app, event)
            clear(app);
            add_sine(app);    
        end

        % Cell edit callback: UITable_add_sine
        function UITable_add_sineCellEdit(app, event)
            clear(app);
        end

        % Key press function: UITable_add_sine
        function UITable_add_sineKeyPress(app, event)
           clear(app);
        end

        % Selection changed function: UITable_add_sine
        function UITable_add_sineSelectionChanged(app, event)
           clear(app);
        end

        % Value changed function: StartFreqHzEditField
        function StartFreqHzEditFieldValueChanged(app, event)

            
        end

        % Value changing function: DurationsecEditField_chirp
        function DurationsecEditField_chirpValueChanging(app, event)
            clear(app);          
        end

        % Value changing function: StartFreqHzEditField
        function StartFreqHzEditFieldValueChanging(app, event)
          
            clear(app);
        end

        % Value changing function: PhaseradEditField
        function PhaseradEditFieldValueChanging(app, event)
           
            clear(app);
        end

        % Value changing function: AmplitudeEditField_chirp
        function AmplitudeEditField_chirpValueChanging(app, event)
            
            clear(app);
        end

        % Value changing function: EndFreqHzEditField
        function EndFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_chirp
        function SampleRateHzEditField_chirpValueChanging(app, event)
          
            clear(app);
        end

        % Value changed function: SweepTypeListBox_chirp
        function SweepTypeListBox_chirpValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_chirp
        function CalculateButton_chirpPushed(app, event)
            clear(app);

            Items = char(app.SweepTypeListBox_chirp.Items);
            im=app.SweepTypeListBox_chirp.Value;
            [ntx]=find_index(Items,im);

            f1=str2num(app.StartFreqHzEditField.Value);
            f2=str2num(app.EndFreqHzEditField.Value);
            T=str2num(app.DurationsecEditField_chirp.Value);
            phi=str2num(app.PhaseradEditField.Value);
            amp=str2num(app.AmplitudeEditField_chirp.Value);
            sr=str2num(app.SampleRateHzEditField_chirp.Value);
            name=app.OutputArrayNameEditField_chirp.Value;

            fmax=max([f1 f2]);
            
            srmin=32*fmax;
            
            if(sr<srmin)
               sr=srmin; 
               out1=sprintf('Sample rate increased to %8.4g Hz',sr); 
               msgbox(out1); 
            end
            
            dt=1/sr;
            nt=floor(T/dt);
            
            t=zeros(nt,1);
            a=zeros(nt,1);
            
            tpi=2*pi;
            
            direction=1;
            
            if(f1>f2)
                temp=f1;
                f1=f2;
                f2=temp;
                direction=2;
            end
            
            k=(f2-f1)/T;
            kh=k/2;
            
            N=log(f2/f1)/log(2);
            
            R=N/T;
            
            den=R*log(2);
            
            if(ntx==1) % linear
            
                for i=1:nt
                    t(i)=(i-1)*dt;
                    arg=phi+tpi*(f1+kh*t(i))*t(i);
                    a(i)=sin(arg);
                end    
                    
            else         % log
            
                for i=1:nt
                    t(i)=(i-1)*dt;    
                    arg=phi+tpi*f1*(2^(R*t(i))-1)/den;
                    a(i)=sin(arg);
                end    
                            
            end
            
            if(direction==2)
                a=flipud(a);
            end
                
            a=a*amp;
            
            yn=app.YaxisLabelEditField_chirp.Value;

            figure(1);
            plot(t,a);
            title('Chirp Signal');
            grid on;
            xlabel('Time (sec)');
            ylabel(yn);

            B=[t a];
            
            assignin('base',name,B);

        end

        % Value changing function: NumberofPulsesEditField
        function NumberofPulsesEditFieldValueChanging(app, event)
 
            clear(app);
        end

        % Value changing function: NominalAmplitudeEditField
        function NominalAmplitudeEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MaxEditField
        function MaxEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MinEditField
        function MinEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_ihs
        function SampleRateHzEditField_ihsValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PrePulseDurationsecEditField
        function PrePulseDurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MaxEditField_ihs
        function MaxEditField_ihsValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MinEditField_ihs
        function MinEditField_ihsValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: CalculateButton_half_sine_series
        function CalculateButton_half_sine_seriesPushed(app, event)
            clear(app); 

            Items = char(app.RandomNumberSeedListBox_ihs.Items);
            im=app.RandomNumberSeedListBox_ihs.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            nnum=str2num(app.NumberofPulsesEditField.Value);
            time_between_max=str2num(app.MaxEditField.Value);
            time_between_min=str2num(app.MinEditField.Value);
            sr=str2num(app.SampleRateHzEditField_ihs.Value);
            
            amp=str2num(app.NominalAmplitudeEditField.Value);
            pre_time=str2num(app.PrePulseDurationsecEditField.Value);
            
            dur_min=(1/1000)*str2num(app.MaxEditField_ihs.Value);
            dur_max=(1/1000)*str2num(app.MinEditField_ihs.Value);
            
            YS=app.YaxisLabelEditField_ihs.Value;
            name=app.OutputArrayPrefixEditField.Value;

            fig_num=1;
            
            tmax=time_between_max;
            tmin=time_between_min;
            dt=1/sr;   
            
            np2=nnum*floor(tmax/dt);
            np1=nnum*floor(tmin/dt);
            
            TT=linspace(0,(np2-1)*dt,np2); 
            
            [~,ii]=min(abs(TT-pre_time));
            if(ii<1)
                ii=1;
            end
            
            jj2=np2/nnum;
            jj1=np1/nnum;
            
            a=zeros(np2,1);
            
            i1=ii;
            
            for ijk=1:nnum
                
                A=amp*(0.9+0.2*rand());
                pulse_duration=(dur_max-dur_min)*rand()+dur_min;
                
                i=0;
                while(1)
                    ttt=i*dt;
                    if(ttt>pulse_duration)
                        break;
                    end
                    a(i1+i)=A*sin(pi*ttt/pulse_duration);
                    i=i+1;
                end
                
                i1=i1+round((jj2-jj1)*rand()+jj1);
            end
            
            TT=fix_size(TT);
            
            h=figure(fig_num);
            plot(TT,a);
            grid on;
            xlabel('Time (sec)');
            ylabel(YS);
            ylim([0,amp*1.2]);
            ZoomHandle = zoom(h);
            set(ZoomHandle,'Motion','horizontal');
            
            data1=[TT a];
            assignin('base', name,data1);   
            
        end

        % Value changed function: NumberofSweepSetsListBox
        function NumberofSweepSetsListBoxValueChanged(app, event)

            msweep(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clear(app); 

            app.n_msweep = str2num(app.NumberofSweepSetsListBox.Value);  
            Nrows=app.n_msweep;

            Ncolumns=4;
             
            for i=1:Nrows
                for j=1:Ncolumns
                    data_s{i,j}='';
                end    
                data_s{i,1}=sprintf('%d',i);
            end  
            
            app.UITable_msweep.Data=data_s;
            
        end

        % Button pushed function: DeleteRowBelowButton
        function DeleteRowBelowButtonPushed(app, event)
            clear(app);

            app.n_msweep = str2num(app.NumberofSweepSetsListBox.Value);  
            Nrows=app.n_msweep;

            Ncolumns=4;
            A=app.UITable_msweep.Data;
             
            sz=size(A);
            Arows=sz(1);

            data_s = cellstr(num2str((1:Nrows)', '%d'));
            data_s(:, 2:Ncolumns) = {''};
             
            if(~isempty(A))
                
                M=min([ Arows Nrows ]);
                
                for i=1:M
                    for j=1:Ncolumns
                        data_s{i,j}='';
                        try
                            data_s{i,j}=A{i,j};
                        catch
                        end
                        
                    end    
                end   
             
            end
             
            nd=str2num(app.RowListBox.Value);
             
            if(nd<=Nrows)
                
                temp=data_s;
                clear data_s;
                
                for i=1:(nd-1)
                    for j=1:Ncolumns
                        try
                            data_s{i,j}=temp{i,j};
                        catch
                        end
                        
                    end  
                    data_s{i,1}=sprintf('%d',i);
                end  
                
                for i=(nd+1):Nrows
                    for j=1:Ncolumns
                        try
                            data_s{i-1,j}=temp{i,j};
                        catch
                        end
                        
                    end 

                end      
                
                Nrows=Nrows-1;
                app.n_msweep = Nrows;
                
                app.NumberofSweepSetsListBox.Value=app.NumberofSweepSetsListBox.Items{Nrows};                   

            end
 
            for i=1:Nrows
                data_s{i,1}=sprintf('%d',i);
            end               
            app.UITable_msweep.Data=data_s;
 
        end

        % Button pushed function: CalculateButton_msweep
        function CalculateButton_msweepPushed(app, event)
            
            try
                save_array_name=app.SaveModelNameEditField.Value;
                MMS.save_array_name=save_array_name; 
            catch 
                warndlg('Enter save array name');
                return;
            end
             
            if(isempty(save_array_name))
                warndlg('Enter save array name');
                return;    
            end
             
            try
                MMS.num=app.n_msweep; 
            catch    
            end
            
            try
                Items = char(app.RateListBox.Items);
                im=app.RateListBox.Value;
                [app.ntype]=find_index(Items,im);     
                MMS.ntype=app.ntype; 
            catch    
            end
            
            try
                MMS.T=app.DurationsecEditField_msweep.Value;
            catch    
            end
            
            try
                MMS.SR=app.SampleRateHzEditField_msweep.Value;
            catch    
            end
             
            try
                MMS.data=app.UITable_msweep.Data;
            catch    
            end 
             
            % % %
             
            %%% structnames = fieldnames(MMS, '-full'); % fields in the struct
              
            % % %
             
            %%%   [writefname, writepname] = uiputfile('*.mat','Save data as');
             
               %%%    writepfname = fullfile(writepname, writefname);
                
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
               
            %%%    elk=sprintf('%s%s',writepname,writefname);
                 
            name=save_array_name;
             
            name=strrep(name,'.mat','');
            name=strrep(name,'_model','');    
            name=sprintf('%s_model.mat',name);
             
            try
                save(name, 'MMS'); 
            catch
                warndlg('Save error');
                return;
            end
             
            out1=sprintf('Save Complete: %s',name);
            disp(out1);
            msgbox(out1);

        end

        % Button pushed function: CalculateButton_load_msweep
        function CalculateButton_load_msweepPushed(app, event)
            
            [filename, pathname] = uigetfile('*.mat', 'Select plot save file');
            
            NAME = [pathname,filename];
            
            struct=load(NAME);
            structnames = fieldnames(struct, '-full'); % fields in the struct
            
            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
            
               MMS=evalin('base','MMS');
            
            catch
               warndlg(' evalin failed ');
               setappdata(0,'eflag',1);   
               return;
            end
            
            %%%%%%%%%%%%

            try
                app.SaveModelNameEditField.Value=MMS.save_array_name; 
            catch 
            end
             
            try
                MMS.num=app.n_msweep; 
            catch    
            end
            
            try
                app.RateListBox.Value=app.RateListBox.Items{MMS.ntype};
            catch    
            end
            
            try
                app.DurationsecEditField_msweep.Value=MMS.T;
            catch    
            end
            
            try
                app.SampleRateHzEditField_msweep.Value=MMS.SR;
            catch    
            end
             
            try
                app.UITable_msweep.Data=MMS.data;
            catch    
            end 
             
        end

        % Button pushed function: GotoHALTHASSButton
        function GotoHALTHASSButtonPushed(app, event)
            vibrationdata_PSD_accel_synth_HALT;
        end

        % Button pushed function: GotoSinemultiplecolumnsButton
        function GotoSinemultiplecolumnsButtonPushed(app, event)
            sine_multiple_columns;
        end

        % Button down function: GenerateTabGroup
        function GenerateTabGroupButtonDown(app, event)
            
        end

        % Button down function: SignalIndexTab
        function SignalIndexTabButtonDown(app, event)
            
        end

        % Button pushed function: SaveButton_cor
        function SaveButton_corPushed(app, event)
            
            Items = char(app.ExportFunctionListBox_cor.Items);
            im=app.ExportFunctionListBox_cor.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel;
            end    
            if(n==2)
                data=app.vel;
            end  
            if(n==3)
                data=app.dispx;
            end        

            name=app.OutputNameEditField_cor.Value;
            assignin('base',name,data);

            msgbox('Save Complete');

        end

        % Button pushed function: CheckforVersionUpdateButton
        function CheckforVersionUpdateButtonPushed(app, event)
            url = 'https://vibrationdata.wordpress.com/2013/05/29/vibrationdata-matlab-signal-analysis-package/';
            web(url);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            update_list(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                sss=sprintf('clear %s',cell_array{i});
                evalin('base', sss);
            end    
            update_list(app);
        end

        % Button pushed function: AnalyzeSignal2Button
        function AnalyzeSignal2ButtonPushed(app, event)
         

            Items = strtrim(app.SelectSignalAnalysis2ListBox.Items);
            im = strtrim(app.SelectSignalAnalysis2ListBox.Value);
            n = find(strcmp(Items, im));

            if(app.indomain==1)
                if(app.isignal==5) % psd
                    if(n==1)
                        vibrationdata_psd_ah;
                    end
                    if(n==2)
                        cpsd_H1_H2_a;
                    end
                    if(n==3)
                        vibrationdata_energy_sd;
                    end
                    if(n==4)
                        power_transmissibility_ah;
                    end
                    if(n==5)
                        smc_s_016_psd_spl_mpe;
                    end
                end
                if(app.isignal==6) % psd envelope
                    if(n==1) % Envelope ers
	                    handles.s= vibrationdata_envelope_ers;         
                    end        
                    if(n==2) % Envelope fds
	                    handles.s= vibrationdata_envelope_fds;         
                    end   
                    if(n==3) % Envelope srs
	                    handles.s= vibrationdata_envelope_th_srs_psd;         
                    end  
                    if(n==4) % PSD MPE per SMC-S-016, 3.27
	                    smc_s_016_psd_spl_mpe;         
                    end 
                end    
                if(app.isignal==9) % srs
                    if(n==1)
                        srs_base_ah;                
                    end
                    if(n==2)
                        handles.s= vibrationdata_srs_base_triaxial;                
                    end
                    if(n==3)
                        handles.s=waterfall_SRS;       
                    end
                    if(n==4)
                        handles.s=SRS_spec_from_time_history;       
                    end
                    if(n==5)
                        temporal_moments_a; 
                    end   
                    if(n==6)
                        srs_batch_alt;
                    end    
                end    
                if(app.isignal==10) % saturation removal
                    if(n==1)
                        Butterworth_filter_saturation_a;             
                    end
                    if(n==2)
                        handles.s=vibrationdata_bessel_filter_saturation;              
                    end
                    if(n==3)
                        handles.s=vibrationdata_mean_filter_saturation; 
                    end   
                end                   
            end

            try
                set(handles.s,'Visible','on');  
            catch
            end

        end

        % Value changed function: SelectSignalAnalysis2ListBox
        function SelectSignalAnalysis2ListBoxValueChanged(app, event)
    
            AnalyzeSignal2ButtonPushed(app, event)
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: UnitsListBox_force
        function UnitsListBox_forceValueChanged(app, event)
            app.ResultsTextArea_sweep_force.Value='';
            units_force(app);
        end

        % Button pushed function: CalculateButton_force
        function CalculateButton_forcePushed(app, event)
            disp(' ');
            disp(' * * * * * * * ');
            disp(' ');
        
            clear(app);
            
            nnum=str2num(app.WindowCyclesEditField_2.Value);
       
            fn=str2num(app.NaturalFrequencyHzEditField_force.Value);
            Q=str2num(app.AmplificationFactorQEditField_force.Value);
            FS=app.BaseExcitationArrayEditField.Value;

            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end 
            
            damp=1/(2*Q);
            
            dt=mean(diff(app.THM(:,1)));

            t=app.THM(:,1);
            f=app.THM(:,2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            mass=str2num(app.MasslbmEditField.Value);
            
            if(app.iu_force==1)
                n_force_mass_unit=1;
                string1=sprintf(' Displacement (inch) '); 
                string8=sprintf(' Velocity (in/sec)  ');
                string15=sprintf(' Acceleration (G) ');
                string22=sprintf(' Transmitted Force (lbf) ');
            else    
                n_force_mass_unit=2;
                string1=sprintf(' Displacement (mm) '); 
                string8=sprintf(' Velocity (m/sec) ');
                string15=sprintf(' Acceleration (G) ');
                string22=sprintf(' Transmitted Force (N) ');    
            end
               
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [aaccel,velox,displ,trans_force]=...
                           arbit_force_function(t,f,dt,mass,fn,damp,n_force_mass_unit);
            
            fig_num=5;

            big_string=sprintf('Peak Responses ');
            big_string=sprintf('%s \n\n %s \n  = %7.3g',big_string,string1,max(abs(displ(:,2))));
            big_string=sprintf('%s \n\n %s \n  = %7.3g',big_string,string8,max(abs(velox(:,2))));
            big_string=sprintf('%s \n\n %s \n  = %7.3g',big_string,string15,max(abs(aaccel(:,2))));   
            big_string=sprintf('%s \n\n %s \n  = %7.3g',big_string,string22,max(abs(trans_force(:,2)))); 

            app.ResultsTextArea_sweep_force.Value=big_string;
            app.ResultsTextArea_sweep_force.Visible='on';
            app.ResultsTextAreaLabel_sweep_force.Visible='on';            
            
            disp(' ');
            disp(big_string);
          

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            name_a=app.AccelResponseArrayEditField_force.Value;
            name_v=app.VelResponseArrayEditField.Value;
            name_d=app.DispResponseArrayEditField.Value;
            
            assignin('base',name_a, aaccel);
            assignin('base',name_v, velox);
            assignin('base',name_d, displ);   

         
            iss=strtrim(app.CalculateListBox_force.Value);
            
            if(strcmp(iss,'Time Histories Only'))
                ijk=1;
            end
            if(strcmp(iss,'Transmissibility'))
                ijk=2;
            end
            if(strcmp(iss,'Transmissibility & Phase'))
                ijk=3;
            end

            if(ijk>=2)
             
                [f,trans,phase]=sine_sweep_transw(app.THM,velox(:,2),ijk,nnum);
                
                figure(fig_num);
                fig_num=fig_num+1;
                
                plot(f,trans);
                title('Sine Sweep Transfer Magnitude (Velocity/Force)');
                xlabel('Frequency (Hz)');
                
                k=1;
                
                if( k>=1 )
                    if(app.iu_force==1)
                        ylabel('Trans (ips/lbf)');
                    else
                        ylabel('Trans ((m/sec)/N)');                       
                    end
                else
                    ylabel('Trans');
                end    
                grid on;
                
                xl = xlim;
                fmin=xl(1);
                fmax=xl(2);
                
                trans=[f trans];
                
                if(ijk==3)
                    
                    t_string='Sine Sweep Magnitude & Phase';
                    
                    ff=f;
                    FRF_p=phase;
                    FRF_m=trans(:,2);
                    if(app.iu_force==1)
                        ylab='Vel (ips)';
                    else
                        ylab='Vel (m/sec)';
                    end
                    md=4;
                    
                    [fig_num]=...
                    plot_magnitude_phase_function_linear(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
                    
                end
                
                [fmax,amax]=find_max(trans);
                
                disp(' ');
                disp('* * * * * * * * * ');
                disp(' ');
                
                if(app.iu_force==1)
                    fprintf('\n  Maximum Transfer Magnitude: (%8.4g Hz, %8.4g ips/lbf) \n\n',amax,fmax);
                else
                    fprintf('\n  Maximum Transfer Magnitude: (%8.4g Hz, %8.4g m/sec/lbf) \n\n',amax,fmax);                    
                end
            end
        end

        % Value changed function: NaturalFrequencyHzEditField_force
        function NaturalFrequencyHzEditField_forceValueChanged(app, event)
            
            app.ResultsTextArea_sweep_force.Value='';
        end

        % Value changed function: MasslbmEditField
        function MasslbmEditFieldValueChanged(app, event)
            
            app.ResultsTextArea_sweep_force.Value='';
        end

        % Value changed function: AmplificationFactorQEditField_force
        function AmplificationFactorQEditField_forceValueChanged(app, event)
            
            app.ResultsTextArea_sweep_force.Value='';
        end

        % Value changed function: NumberofRowsListBox
        function NumberofRowsListBoxValueChanged(app, event)
            ChangeManualData(app);
        end

        % Value changed function: NumberofColumnsListBox
        function NumberofColumnsListBoxValueChanged(app, event)
       
            ChangeManualData(app);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            B=app.UITableManual.Data;

            nrow=str2num(app.NumberofRowsListBox.Value);
            ncol=str2num(app.NumberofColumnsListBox.Value);

            q=zeros(nrow,ncol);

            k=1;
            for j=1:ncol
                for i=1:nrow
                    q(i,j)=B(k);
                    k=k+1;
                end
            end

            name=app.OutputArrayNameEditField_Manual.Value;

            assignin('base', name, q );     

            msgbox('Data Saved');


        end

        % Button pushed function: BeginAnalysisIIButton
        function BeginAnalysisIIButtonPushed(app, event)
            Items = strtrim(app.SelectAnalysisIIListBox.Items);
            im = strtrim(app.SelectAnalysisIIListBox.Value);
            n = find(strcmp(Items, im));

            if(n==1)
                mdof_srs_a;  
            end
            if(n==2)
                handles.s=vibrationdata_mdof_srs_multiple;  
            end
            if(n==3)
                handles.s=vibrationdata_mdof_srs_multiple_sv;  
            end
            
            try
                set(handles.s,'Visible','on'); 
            catch
            end

        end

        % Button pushed function: BeginAnalysisIButton
        function BeginAnalysisIButtonPushed(app, event)
            Items = strtrim(app.SelectAnalysisIListBox.Items);
            im = strtrim(app.SelectAnalysisIListBox.Value);
            n = find(strcmp(Items, im));        

            if(n==1)
                amplitude_conversion_utilities_a;
            end
            if(n==2)
                classical_pulse_base_input_a;  
            end
            if(n==3)
                sdof_response_time_domain;  
            end
            if(n==4)
                srs_base_ah;  
            end
            if(n==5)
                handles.s=compare_two_half_sines;  
            end
            if(n==6)
                handles.s=half_sine_shock_test;  
            end
            if(n==7)
                handles.s=classical_shock_test_shaker;  
            end
            
            if(n==8)
                accidental_drop_shock_a;  
            end
            if(n==9)
                handles.s=vibrationdata_shock_effective_duration;  
            end
            if(n==10)
                temporal_moments_a;  
            end
            if(n==11)
                shock_propagation_a;  
            end
            if(n==12)
                vibrationdata_stress_velocity_a;  
            end
            if(n==13)
                spacecraft_clamp_band_SRS;  
            end
            if(n==14)
                handles.s=srs_spec_convert_Q; 
            end
            if(n==15)
                srs_batch_alt; 
            end
            if(n==16)
                handles.s=vibrationdata_accel_SRS_PV_SRS; 
            end
            if(n==17)
                handles.s=classical_pulse_applied_force;
            end
            if(n==18)
                handles.s= srs_classical_pulse_synth;
            end    
            try
                set(handles.s,'Visible','on'); 
            catch
            end


        end

        % Button down function: WorkspaceTab
        function WorkspaceTabButtonDown(app, event)
            update_list(app);
        end

        % Button pushed function: CalculateButton_pink_2
        function CalculateButton_pink_2Pushed(app, event)
            try
                [y,Fs] = audioread(app.afilename);
                sound(y,Fs);
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.vibrationdata_AppUIFigure = uifigure('Visible', 'off');
            app.vibrationdata_AppUIFigure.Name = 'vibrationdata_App';
            app.TabGroup = uitabgroup(app.vibrationdata_AppUIFigure);
            app.SignalAnalysisTab = uitab(app.TabGroup);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.SelectInputDomainListBoxLabel = uilabel(app.SignalAnalysisTab);
            app.SelectInputDomainListBoxLabel.Text = 'Select Input Domain';
            app.SelectInputDomainListBox = uilistbox(app.SignalAnalysisTab);
            app.SelectInputDomainListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputDomainListBoxValueChanged, true);
            app.SelectAmplitudeTypeListBoxLabel = uilabel(app.SignalAnalysisTab);
            app.SelectAmplitudeTypeListBoxLabel.Text = 'Select Amplitude Type';
            app.SelectAmplitudeTypeListBox = uilistbox(app.SignalAnalysisTab);
            app.SelectAmplitudeTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAmplitudeTypeListBoxValueChanged, true);
            app.SelectSignalAnalysis1ListBoxLabel = uilabel(app.SignalAnalysisTab);
            app.SelectSignalAnalysis1ListBoxLabel.Text = 'Select Signal Analysis 1';
            app.SelectSignalAnalysis1ListBox = uilistbox(app.SignalAnalysisTab);
            app.SelectSignalAnalysis1ListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeSignalButtonPushed, true);
            app.AnalyzeSignal1Button = uibutton(app.SignalAnalysisTab, 'push');
            app.AnalyzeSignal1Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSignalButtonPushed, true);
            app.AnalyzeSignal1Button.Text = 'Analyze Signal 1';
            app.SelectSignalAnalysis2ListBoxLabel = uilabel(app.SignalAnalysisTab);
            app.SelectSignalAnalysis2ListBoxLabel.Text = 'Select Signal Analysis 2';
            app.SelectSignalAnalysis2ListBox = uilistbox(app.SignalAnalysisTab);
            app.SelectSignalAnalysis2ListBox.ValueChangedFcn = createCallbackFcn(app, @SelectSignalAnalysis2ListBoxValueChanged, true);
            app.AnalyzeSignal2Button = uibutton(app.SignalAnalysisTab, 'push');
            app.AnalyzeSignal2Button.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSignal2ButtonPushed, true);
            app.AnalyzeSignal2Button.Text = 'Analyze Signal 2';
            app.GenerateTab = uitab(app.TabGroup);
            app.GenerateTab.Title = 'Generate';
            app.GenerateTabGroup = uitabgroup(app.GenerateTab);
            app.GenerateTabGroup.SelectionChangedFcn = createCallbackFcn(app, @GenerateTabGroupSelectionChanged, true);
            app.GenerateTabGroup.ButtonDownFcn = createCallbackFcn(app, @GenerateTabGroupButtonDown, true);
            app.SignalIndexTab = uitab(app.GenerateTabGroup);
            app.SignalIndexTab.Title = 'Signal Index';
            app.SignalIndexTab.ButtonDownFcn = createCallbackFcn(app, @SignalIndexTabButtonDown, true);
            app.GenerateListBox = uilistbox(app.SignalIndexTab);
            app.GenerateListBox.Items = {'index', 'sine, multiple sine tones, damped sine & cosine', 'sine sweep', 'white noise various', 'random with skewness & kurtosis, Sarkani random, pink noise', 'half-sine, versed half-sine pulse, sawtooth, rectangular', 'wavelet', 'IEEE std 693 Sine Beat', 'students t distribution', 'add sine tones to existing signal', 'HALT/HASS simulation', 'chirp', 'unit impulse', 'sine, multiple columns', 'multiple sine sweeps', 'generate series of intermittent half-sine pulses'};
            app.GenerateListBox.ValueChangedFcn = createCallbackFcn(app, @GenerateListBoxValueChanged, true);
            app.GenerateListBox.Value = 'index';
            app.SelectSignalLabel = uilabel(app.SignalIndexTab);
            app.SelectSignalLabel.Text = 'Select Signal';
            app.PerformAnalysisButton = uibutton(app.SignalIndexTab, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.aTab = uitab(app.GenerateTabGroup);
            app.aTab.Title = 'a';
            app.TabGroup_3 = uitabgroup(app.aTab);
            app.SineTab = uitab(app.TabGroup_3);
            app.SineTab.Title = 'Sine';
            app.AmplitudeEditFieldLabel = uilabel(app.SineTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineTab, 'text');
            app.AmplitudeEditField.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanging, true);
            app.FrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineTab, 'text');
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanging, true);
            app.PhaseAngledegEditFieldLabel = uilabel(app.SineTab);
            app.PhaseAngledegEditFieldLabel.Text = 'Phase Angle (deg)';
            app.PhaseAngledegEditField = uieditfield(app.SineTab, 'text');
            app.PhaseAngledegEditField.ValueChangingFcn = createCallbackFcn(app, @PhaseAngledegEditFieldValueChanging, true);
            app.PhaseAngledegEditField.Value = '0';
            app.RectifyListBoxLabel = uilabel(app.SineTab);
            app.RectifyListBoxLabel.Text = 'Rectify';
            app.RectifyListBox = uilistbox(app.SineTab);
            app.RectifyListBox.Items = {'No', 'Yes'};
            app.RectifyListBox.ValueChangedFcn = createCallbackFcn(app, @RectifyListBoxValueChanged, true);
            app.RectifyListBox.Value = 'No';
            app.CalculateButton_sine = uibutton(app.SineTab, 'push');
            app.CalculateButton_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sinePushed, true);
            app.CalculateButton_sine.Text = 'Calculate';
            app.MultipleSineTonesTab = uitab(app.TabGroup_3);
            app.MultipleSineTonesTab.Title = 'Multiple Sine Tones';
            app.NumberofTonesListBoxLabel = uilabel(app.MultipleSineTonesTab);
            app.NumberofTonesListBoxLabel.Text = {'Number '; 'of Tones'};
            app.NumberofTonesListBox = uilistbox(app.MultipleSineTonesTab);
            app.NumberofTonesListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofTonesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofTonesListBoxValueChanged, true);
            app.NumberofTonesListBox.Value = '2';
            app.UITable = uitable(app.MultipleSineTonesTab);
            app.UITable.ColumnName = {'Amplitude'; 'Freq (Hz)'; 'Phase (deg)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.CalculateButton_multi = uibutton(app.MultipleSineTonesTab, 'push');
            app.CalculateButton_multi.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_multiPushed, true);
            app.CalculateButton_multi.Text = 'Calculate';
            app.CosineTab = uitab(app.TabGroup_3);
            app.CosineTab.Title = 'Cosine';
            app.CalculateButton_cosine = uibutton(app.CosineTab, 'push');
            app.CalculateButton_cosine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_cosinePushed, true);
            app.CalculateButton_cosine.Text = 'Calculate';
            app.AmplitudeEditField_2Label = uilabel(app.CosineTab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.CosineTab, 'text');
            app.AmplitudeEditField_2.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanging, true);
            app.FrequencyHzEditField_2Label = uilabel(app.CosineTab);
            app.FrequencyHzEditField_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_2 = uieditfield(app.CosineTab, 'text');
            app.FrequencyHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditField_2ValueChanging, true);
            app.DampedSineTab = uitab(app.TabGroup_3);
            app.DampedSineTab.Title = 'Damped Sine';
            app.FrequencyHzEditField_3Label = uilabel(app.DampedSineTab);
            app.FrequencyHzEditField_3Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_dsine = uieditfield(app.DampedSineTab, 'text');
            app.FrequencyHzEditField_dsine.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditField_dsineValueChanging, true);
            app.DampingRatio1EditFieldLabel = uilabel(app.DampedSineTab);
            app.DampingRatio1EditFieldLabel.Text = 'Damping Ratio < 1';
            app.DampingRatio1EditField = uieditfield(app.DampedSineTab, 'text');
            app.DampingRatio1EditField.ValueChangingFcn = createCallbackFcn(app, @DampingRatio1EditFieldValueChanging, true);
            app.CalculateButton_damped_sine = uibutton(app.DampedSineTab, 'push');
            app.CalculateButton_damped_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_damped_sinePushed, true);
            app.CalculateButton_damped_sine.Text = 'Calculate';
            app.DelayTimeEditFieldLabel = uilabel(app.DampedSineTab);
            app.DelayTimeEditFieldLabel.Text = 'Delay Time (sec)';
            app.DelayTimeEditField = uieditfield(app.DampedSineTab, 'text');
            app.DelayTimeEditField.ValueChangingFcn = createCallbackFcn(app, @DelayTimeEditFieldValueChanging, true);
            app.DelayTimeEditFieldLabel_2 = uilabel(app.DampedSineTab);
            app.DelayTimeEditFieldLabel_2.Text = 'Amplitude';
            app.AmplitudeEditField_dsine = uieditfield(app.DampedSineTab, 'text');
            app.DurationsecEditFieldLabel = uilabel(app.aTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.aTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.aTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.aTab, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.YaxisLabelEditFieldLabel = uilabel(app.aTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.aTab, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.SaveButton = uibutton(app.aTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResultsTextAreaLabel = uilabel(app.aTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.aTab);
            app.OutputNameEditFieldLabel = uilabel(app.aTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.aTab, 'text');
            app.OutputNameEditField.Value = 'sine';
            app.bTab_2 = uitab(app.GenerateTabGroup);
            app.bTab_2.Title = 'b';
            app.TabGroup_4 = uitabgroup(app.bTab_2);
            app.SineSweepTab = uitab(app.TabGroup_4);
            app.SineSweepTab.Title = 'Sine Sweep';
            app.GenerateButton = uibutton(app.SineSweepTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SweepTypeListBoxLabel = uilabel(app.SineSweepTab);
            app.SweepTypeListBoxLabel.Text = 'Sweep Type';
            app.SweepTypeListBox = uilistbox(app.SineSweepTab);
            app.SweepTypeListBox.Items = {'linear', 'log'};
            app.SweepTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SweepTypeListBoxValueChanged, true);
            app.SweepTypeListBox.Value = 'linear';
            app.SweepDirectionListBoxLabel = uilabel(app.SineSweepTab);
            app.SweepDirectionListBoxLabel.Text = 'Sweep Direction';
            app.SweepDirectionListBox = uilistbox(app.SineSweepTab);
            app.SweepDirectionListBox.Items = {'up', 'down', 'up & down'};
            app.SweepDirectionListBox.Value = 'up';
            app.SampleRateHzEditFieldLabel_sweep = uilabel(app.SineSweepTab);
            app.SampleRateHzEditFieldLabel_sweep.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_sweep = uieditfield(app.SineSweepTab, 'numeric');
            app.DurationsecEditFieldLabel_sweep = uilabel(app.SineSweepTab);
            app.DurationsecEditFieldLabel_sweep.Text = 'Duration (sec)';
            app.DurationsecEditField_sweep = uieditfield(app.SineSweepTab, 'numeric');
            app.TimeOptionListBoxLabel = uilabel(app.SineSweepTab);
            app.TimeOptionListBoxLabel.Text = 'Time Option';
            app.TimeOptionListBox = uilistbox(app.SineSweepTab);
            app.TimeOptionListBox.Items = {'Duration', 'Sweep Rate'};
            app.TimeOptionListBox.ValueChangedFcn = createCallbackFcn(app, @TimeOptionListBoxValueChanged, true);
            app.TimeOptionListBox.Value = 'Duration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SineSweepTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SineSweepTab, 'text');
            app.OutputArrayNameEditField.Value = 'sine_sweep';
            app.NumberofCoordinatesLabel = uilabel(app.SineSweepTab);
            app.NumberofCoordinatesLabel.Text = {'Number of '; 'Coordinates'};
            app.NumberofCoordinatesListBox = uilistbox(app.SineSweepTab);
            app.NumberofCoordinatesListBox.Items = {'2', '3', '4', '5', '6'};
            app.NumberofCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCoordinatesListBoxValueChanged, true);
            app.NumberofCoordinatesListBox.Value = '2';
            app.UITable_sweep = uitable(app.SineSweepTab);
            app.UITable_sweep.ColumnName = {'Freq (Hz)'; 'Amplitude'};
            app.UITable_sweep.RowName = {};
            app.UITable_sweep.ColumnEditable = true;
            app.CalculateSpectrumFunctionLabel = uilabel(app.SineSweepTab);
            app.CalculateSpectrumFunctionLabel.Text = {'Calculate Spectrum '; 'Function'};
            app.CalculateSpectrumFunctionListBox = uilistbox(app.SineSweepTab);
            app.CalculateSpectrumFunctionListBox.Items = {'Yes', 'No'};
            app.CalculateSpectrumFunctionListBox.Value = 'No';
            app.NumberofOctavesEditFieldLabel = uilabel(app.SineSweepTab);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.SineSweepTab, 'numeric');
            app.SDOFResponseBaseExcitationTab = uitab(app.TabGroup_4);
            app.SDOFResponseBaseExcitationTab.Title = 'SDOF Response, Base Excitation';
            app.CalculateButton_base = uibutton(app.SDOFResponseBaseExcitationTab, 'push');
            app.CalculateButton_base.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_basePushed, true);
            app.CalculateButton_base.Text = 'Calculate';
            app.ResultsTextAreaLabel_sweep = uilabel(app.SDOFResponseBaseExcitationTab);
            app.ResultsTextAreaLabel_sweep.Text = 'Results';
            app.ResultsTextArea_sweep = uitextarea(app.SDOFResponseBaseExcitationTab);
            app.ResultsTextArea_sweep.Editable = 'off';
            app.Image = uiimage(app.SDOFResponseBaseExcitationTab);
            app.Image.ImageSource = 'sdof_base.jpg';
            app.Label_3 = uilabel(app.SDOFResponseBaseExcitationTab);
            app.Label_3.Text = 'The Base Excitation Array must have two columns:  time (sec) & accel (G)';
            app.BaseExcitationArrayEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.BaseExcitationArrayEditFieldLabel.Text = 'Base Excitation Array';
            app.BaseExcitationArrayEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.AccelResponseArrayEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.AccelResponseArrayEditFieldLabel.Text = 'Accel Response Array';
            app.AccelResponseArrayEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.AccelResponseArrayEditField.Value = 'accel_response';
            app.RelDispResponseArrayEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.RelDispResponseArrayEditFieldLabel.Text = 'Rel Disp Response Array';
            app.RelDispResponseArrayEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.RelDispResponseArrayEditField.Value = 'rel_disp_response';
            app.CalculateListBoxLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.CalculateListBoxLabel.Text = 'Calculate';
            app.CalculateListBox = uilistbox(app.SDOFResponseBaseExcitationTab);
            app.CalculateListBox.Items = {'Time Histories Only', 'Transmissibility', 'Transmissibility & Phase'};
            app.CalculateListBox.Value = 'Transmissibility';
            app.Label_4 = uilabel(app.SDOFResponseBaseExcitationTab);
            app.Label_4.Text = 'The numerical engine is the Smallwood ramp invariant digital recursive filtering relationship.';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.AmplificationFactorQEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.TransmissibilityArrayEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.TransmissibilityArrayEditFieldLabel.Text = 'Transmissibility Array';
            app.TransmissibilityArrayEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.TransmissibilityArrayEditField.Value = 'transmissibility';
            app.WindowCyclesEditFieldLabel = uilabel(app.SDOFResponseBaseExcitationTab);
            app.WindowCyclesEditFieldLabel.Text = 'Window Cycles';
            app.WindowCyclesEditField = uieditfield(app.SDOFResponseBaseExcitationTab, 'text');
            app.WindowCyclesEditField.Value = '20';
            app.SDOFResponseAppliedForceTab = uitab(app.TabGroup_4);
            app.SDOFResponseAppliedForceTab.Title = 'SDOF Response, Applied Force';
            app.CalculateButton_force = uibutton(app.SDOFResponseAppliedForceTab, 'push');
            app.CalculateButton_force.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_forcePushed, true);
            app.CalculateButton_force.Text = 'Calculate';
            app.Image_2 = uiimage(app.SDOFResponseAppliedForceTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'sdof_force.jpg');
            app.Label_ForceArray = uilabel(app.SDOFResponseAppliedForceTab);
            app.Label_ForceArray.Text = 'The Force Array must have two columns:  time (sec) & force';
            app.Label_7 = uilabel(app.SDOFResponseAppliedForceTab);
            app.Label_7.Text = 'The numerical engine is a ramp invariant digital recursive filtering relationship.';
            app.ResultsTextAreaLabel_sweep_force = uilabel(app.SDOFResponseAppliedForceTab);
            app.ResultsTextAreaLabel_sweep_force.Text = 'Results';
            app.ResultsTextArea_sweep_force = uitextarea(app.SDOFResponseAppliedForceTab);
            app.ResultsTextArea_sweep_force.Editable = 'off';
            app.AppliedForceArrayEditFieldLabel = uilabel(app.SDOFResponseAppliedForceTab);
            app.AppliedForceArrayEditFieldLabel.Text = 'Applied Force Array';
            app.AppliedForceArrayEditField = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.AccelResponseArrayEditField_2Label = uilabel(app.SDOFResponseAppliedForceTab);
            app.AccelResponseArrayEditField_2Label.Text = 'Accel Response Array';
            app.AccelResponseArrayEditField_force = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.AccelResponseArrayEditField_force.Value = 'accel_response';
            app.DispResponseArrayEditFieldLabel = uilabel(app.SDOFResponseAppliedForceTab);
            app.DispResponseArrayEditFieldLabel.Text = 'Disp Response Array';
            app.DispResponseArrayEditField = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.DispResponseArrayEditField.Value = 'disp_response';
            app.CalculateListBox_2Label = uilabel(app.SDOFResponseAppliedForceTab);
            app.CalculateListBox_2Label.Text = 'Calculate';
            app.CalculateListBox_force = uilistbox(app.SDOFResponseAppliedForceTab);
            app.CalculateListBox_force.Items = {'Time Histories Only', 'Transmissibility', 'Transmissibility & Phase'};
            app.CalculateListBox_force.Value = 'Time Histories Only';
            app.NaturalFrequencyHzEditField_2Label = uilabel(app.SDOFResponseAppliedForceTab);
            app.NaturalFrequencyHzEditField_2Label.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField_force = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.NaturalFrequencyHzEditField_force.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_forceValueChanged, true);
            app.AmplificationFactorQEditField_2Label = uilabel(app.SDOFResponseAppliedForceTab);
            app.AmplificationFactorQEditField_2Label.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField_force = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.AmplificationFactorQEditField_force.ValueChangedFcn = createCallbackFcn(app, @AmplificationFactorQEditField_forceValueChanged, true);
            app.AmplificationFactorQEditField_force.Value = '10';
            app.VelResponseArrayEditFieldLabel = uilabel(app.SDOFResponseAppliedForceTab);
            app.VelResponseArrayEditFieldLabel.Text = 'Vel Response Array';
            app.VelResponseArrayEditField = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.VelResponseArrayEditField.Value = 'vel_response';
            app.UnitsListBoxLabel = uilabel(app.SDOFResponseAppliedForceTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox_force = uilistbox(app.SDOFResponseAppliedForceTab);
            app.UnitsListBox_force.Items = {'lbf, G, in/sec, in', 'N, G , m/sec,  mm'};
            app.UnitsListBox_force.ValueChangedFcn = createCallbackFcn(app, @UnitsListBox_forceValueChanged, true);
            app.UnitsListBox_force.Value = 'lbf, G, in/sec, in';
            app.MasslbmEditFieldLabel = uilabel(app.SDOFResponseAppliedForceTab);
            app.MasslbmEditFieldLabel.Text = 'Mass (lbm)';
            app.MasslbmEditField = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.MasslbmEditField.ValueChangedFcn = createCallbackFcn(app, @MasslbmEditFieldValueChanged, true);
            app.WindowCyclesEditField_2Label = uilabel(app.SDOFResponseAppliedForceTab);
            app.WindowCyclesEditField_2Label.Text = 'Window Cycles';
            app.WindowCyclesEditField_2 = uieditfield(app.SDOFResponseAppliedForceTab, 'text');
            app.WindowCyclesEditField_2.Value = '20';
            app.cTab = uitab(app.GenerateTabGroup);
            app.cTab.Title = 'c';
            app.TabGroup6 = uitabgroup(app.cTab);
            app.WhiteBasicTab = uitab(app.TabGroup6);
            app.WhiteBasicTab.Title = 'White Basic';
            app.CalculateButton_white_noise_basic = uibutton(app.WhiteBasicTab, 'push');
            app.CalculateButton_white_noise_basic.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_white_noise_basicPushed, true);
            app.CalculateButton_white_noise_basic.Text = 'Calculate';
            app.StdDevEditFieldLabel = uilabel(app.WhiteBasicTab);
            app.StdDevEditFieldLabel.Text = 'Std Dev ';
            app.StdDevEditField = uieditfield(app.WhiteBasicTab, 'text');
            app.StdDevEditField.ValueChangingFcn = createCallbackFcn(app, @StdDevEditFieldValueChanging, true);
            app.DurationsecEditField_2Label = uilabel(app.WhiteBasicTab);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.DurationsecEditField_white.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_whiteValueChanging, true);
            app.SampleRateHzEditField_2Label = uilabel(app.WhiteBasicTab);
            app.SampleRateHzEditField_2Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.SampleRateHzEditField_white.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_whiteValueChanging, true);
            app.RandomNumberSeedListBoxLabel = uilabel(app.WhiteBasicTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.WhiteBasicTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.ValueChangedFcn = createCallbackFcn(app, @RandomNumberSeedListBoxValueChanged, true);
            app.RandomNumberSeedListBox.Value = '1';
            app.OutputNameEditField_2Label = uilabel(app.WhiteBasicTab);
            app.OutputNameEditField_2Label.Text = 'Output Name';
            app.OutputNameEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.OutputNameEditField_white.Value = 'white';
            app.WhiteCorrectedTab = uitab(app.TabGroup6);
            app.WhiteCorrectedTab.Title = 'White Corrected';
            app.WhiteCorrectedTab.ButtonDownFcn = createCallbackFcn(app, @WhiteCorrectedTabButtonDown, true);
            app.TabGroup8 = uitabgroup(app.WhiteCorrectedTab);
            app.CalculateTab = uitab(app.TabGroup8);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton_white_noise_corrected = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_white_noise_corrected.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_white_noise_correctedPushed, true);
            app.CalculateButton_white_noise_corrected.Text = 'Calculate';
            app.DurationTaperPercentEditFieldLabel_cor = uilabel(app.CalculateTab);
            app.DurationTaperPercentEditFieldLabel_cor.Text = {'Duration Taper '; 'Percent'};
            app.DurationTaperPercentEditField_cor = uieditfield(app.CalculateTab, 'text');
            app.DurationTaperPercentEditField_cor.Value = '1';
            app.SampleRateHzEditField_4Label = uilabel(app.CalculateTab);
            app.SampleRateHzEditField_4Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.DurationsecEditField_5Label_3 = uilabel(app.CalculateTab);
            app.DurationsecEditField_5Label_3.Text = 'Duration (sec)';
            app.DurationsecEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.StdDevEditField_2Label_2 = uilabel(app.CalculateTab);
            app.StdDevEditField_2Label_2.Text = 'Std Dev';
            app.StdDevEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.SelectUnitsListBoxLabel = uilabel(app.CalculateTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.CalculateTab);
            app.SelectUnitsListBox.Items = {'G, in/sec, in      ', 'G, cm/sec, mm      ', 'm/sec^2, cm/sec, mm'};
            app.SelectUnitsListBox.Value = 'G, in/sec, in      ';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor = uilabel(app.CalculateTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor.Text = {'Select Trend Removal '; 'Between Integrations'};
            app.T2_ListBox_cor = uilistbox(app.CalculateTab);
            app.T2_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T2_ListBox_cor.Value = 'None';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2 = uilabel(app.CalculateTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2.Text = {'Select Trend Removal '; 'After Integrations'};
            app.T3_ListBox_cor = uilistbox(app.CalculateTab);
            app.T3_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T3_ListBox_cor.Value = 'None';
            app.RandomNumberSeedListBox_3Label_2 = uilabel(app.CalculateTab);
            app.RandomNumberSeedListBox_3Label_2.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox_cor = uilistbox(app.CalculateTab);
            app.RandomNumberSeedListBox_cor.Items = {'1', 'None'};
            app.RandomNumberSeedListBox_cor.Value = '1';
            app.SaveTab = uitab(app.TabGroup8);
            app.SaveTab.Title = 'Save';
            app.SaveButton_cor = uibutton(app.SaveTab, 'push');
            app.SaveButton_cor.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_corPushed, true);
            app.SaveButton_cor.Text = 'Save';
            app.ExportFunctionListBoxLabel_cor = uilabel(app.SaveTab);
            app.ExportFunctionListBoxLabel_cor.Text = 'Export Function';
            app.ExportFunctionListBox_cor = uilistbox(app.SaveTab);
            app.ExportFunctionListBox_cor.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.ExportFunctionListBox_cor.Value = 'Acceleration';
            app.OutputNameEditFieldLabel_cor = uilabel(app.SaveTab);
            app.OutputNameEditFieldLabel_cor.Text = 'Output Name';
            app.OutputNameEditField_cor = uieditfield(app.SaveTab, 'text');
            app.TwoCorrelatedWhiteSignalsTab = uitab(app.TabGroup6);
            app.TwoCorrelatedWhiteSignalsTab.Title = 'Two Correlated White Signals';
            app.CalculateButton_two_correlated_white = uibutton(app.TwoCorrelatedWhiteSignalsTab, 'push');
            app.CalculateButton_two_correlated_white.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_two_correlated_whitePushed, true);
            app.CalculateButton_two_correlated_white.Text = 'Calculate';
            app.OutputNameEditField_2Label_6 = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.OutputNameEditField_2Label_6.Text = 'Output Array Prefix';
            app.OutputNameEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.OutputNameEditField_two_white.Value = 'signal';
            app.CorrelationCoefficientEditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.CorrelationCoefficientEditFieldLabel.Text = 'Correlation Coefficient';
            app.CorrelationCoefficientEditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.CorrelationCoefficientEditField.Value = '0';
            app.DurationsecEditField_5Label = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.DurationsecEditField_5Label.Text = 'Duration (sec)';
            app.DurationsecEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.DurationsecEditField_two_white.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_two_whiteValueChanging, true);
            app.SampleRateHzEditField_2Label_2 = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.SampleRateHzEditField_2Label_2.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.SampleRateHzEditField_two_white.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_two_whiteValueChanging, true);
            app.StdDev1EditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.StdDev1EditFieldLabel.Text = 'Std Dev 1';
            app.StdDev1EditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.StdDev1EditField.ValueChangingFcn = createCallbackFcn(app, @StdDev1EditFieldValueChanging, true);
            app.StdDev2EditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.StdDev2EditFieldLabel.Text = 'Std Dev 2';
            app.StdDev2EditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.StdDev2EditField.ValueChangingFcn = createCallbackFcn(app, @StdDev2EditFieldValueChanging, true);
            app.AddWhitetoSignalTab = uitab(app.TabGroup6);
            app.AddWhitetoSignalTab.Title = 'Add White to Signal';
            app.CalculateButton_add_white_noise = uibutton(app.AddWhitetoSignalTab, 'push');
            app.CalculateButton_add_white_noise.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_add_white_noisePushed, true);
            app.CalculateButton_add_white_noise.Text = 'Calculate';
            app.OutputNameEditField_2Label_5 = uilabel(app.AddWhitetoSignalTab);
            app.OutputNameEditField_2Label_5.Text = 'Output Name';
            app.OutputNameEditField_add_white = uieditfield(app.AddWhitetoSignalTab, 'text');
            app.OutputNameEditField_add_white.Value = 'composite';
            app.StdDevEditField_2Label = uilabel(app.AddWhitetoSignalTab);
            app.StdDevEditField_2Label.Text = 'Std Dev ';
            app.StdDevEditField_add_white = uieditfield(app.AddWhitetoSignalTab, 'text');
            app.text1 = uilabel(app.AddWhitetoSignalTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.AddWhitetoSignalTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.UpdateListButton = uibutton(app.AddWhitetoSignalTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.AddWhitetoSignalTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox_add_white = uilistbox(app.AddWhitetoSignalTab);
            app.SelectInputArrayListBox_add_white.Items = {'Item 1'};
            app.ResultsTextAreaLabel_white = uilabel(app.cTab);
            app.ResultsTextAreaLabel_white.Text = 'Results';
            app.ResultsTextArea_white = uitextarea(app.cTab);
            app.BandlimitviaFilteringListBoxLabel = uilabel(app.cTab);
            app.BandlimitviaFilteringListBoxLabel.Text = 'Band-limit via Filtering?';
            app.BandlimitviaFilteringListBox = uilistbox(app.cTab);
            app.BandlimitviaFilteringListBox.Items = {'yes, low-pass', 'yes, band-pass', 'none'};
            app.BandlimitviaFilteringListBox.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaFilteringListBoxValueChanged, true);
            app.BandlimitviaFilteringListBox.Value = 'none';
            app.LowpassFreqHzEditFieldLabel = uilabel(app.cTab);
            app.LowpassFreqHzEditFieldLabel.Text = 'Low-pass Freq (Hz)';
            app.LowpassFreqHzEditField = uieditfield(app.cTab, 'text');
            app.LowpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowpassFreqHzEditFieldValueChanging, true);
            app.HighpassFreqHzEditFieldLabel = uilabel(app.cTab);
            app.HighpassFreqHzEditFieldLabel.Text = 'High-pass Freq (Hz)';
            app.HighpassFreqHzEditField = uieditfield(app.cTab, 'text');
            app.HighpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @HighpassFreqHzEditFieldValueChanging, true);
            app.YaxisLabelEditField_2Label_3 = uilabel(app.cTab);
            app.YaxisLabelEditField_2Label_3.Text = 'Y-axis Label';
            app.YaxisLabelEditField_white_2 = uieditfield(app.cTab, 'text');
            app.YaxisLabelEditField_white_2.Value = 'Accel (G)';
            app.dTab = uitab(app.GenerateTabGroup);
            app.dTab.Title = 'd';
            app.TabGroup7 = uitabgroup(app.dTab);
            app.RandomwithSkewnessKurtosisTab = uitab(app.TabGroup7);
            app.RandomwithSkewnessKurtosisTab.Title = 'Random with Skewness & Kurtosis';
            app.CalculateButton_sk = uibutton(app.RandomwithSkewnessKurtosisTab, 'push');
            app.CalculateButton_sk.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_skPushed, true);
            app.CalculateButton_sk.Text = 'Calculate';
            app.OutputNameEditField_2Label_3 = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.OutputNameEditField_2Label_3.Text = 'Output Name';
            app.OutputNameEditField_sk = uieditfield(app.RandomwithSkewnessKurtosisTab, 'text');
            app.OutputNameEditField_sk.Value = 'random';
            app.Filteratsamplerate10Label_2 = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.Filteratsamplerate10Label_2.Text = 'Filter at (sample rate ) / 10';
            app.BandlimitviaLowpassFilterListBoxLabel_2 = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.BandlimitviaLowpassFilterListBoxLabel_2.Text = {'Band-limit via '; 'Lowpass Filter'};
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Items = {'yes', 'no'};
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaLowpassFilterListBox_Sarkani_skValueChanged, true);
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Value = 'yes';
            app.KurtosisEditFieldLabel = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.RandomwithSkewnessKurtosisTab, 'text');
            app.KurtosisEditField.ValueChangingFcn = createCallbackFcn(app, @KurtosisEditFieldValueChanging, true);
            app.RandomNumberSeedListBox_2Label = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.RandomNumberSeedListBox_2Label.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox_2 = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.RandomNumberSeedListBox_2.Items = {'1', 'None'};
            app.RandomNumberSeedListBox_2.ValueChangedFcn = createCallbackFcn(app, @RandomNumberSeedListBox_2ValueChanged, true);
            app.RandomNumberSeedListBox_2.Value = '1';
            app.SkewnessListBoxLabel = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.SkewnessListBoxLabel.Text = 'Skewness';
            app.SkewnessListBox = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.SkewnessListBox.Items = {'0', '0.1', '0.2', '0.3', '0.4', '0.5'};
            app.SkewnessListBox.ValueChangedFcn = createCallbackFcn(app, @SkewnessListBoxValueChanged, true);
            app.SkewnessListBox.Value = '0';
            app.SarkaniRandomTab = uitab(app.TabGroup7);
            app.SarkaniRandomTab.Title = 'Sarkani Random';
            app.CalculateButton_Sarkani = uibutton(app.SarkaniRandomTab, 'push');
            app.CalculateButton_Sarkani.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SarkaniPushed, true);
            app.CalculateButton_Sarkani.Text = 'Calculate';
            app.OutputNameEditField_2Label_4 = uilabel(app.SarkaniRandomTab);
            app.OutputNameEditField_2Label_4.Text = 'Output Name';
            app.OutputNameEditField_Sarkani = uieditfield(app.SarkaniRandomTab, 'text');
            app.OutputNameEditField_Sarkani.Value = 'random';
            app.Filteratsamplerate10Label = uilabel(app.SarkaniRandomTab);
            app.Filteratsamplerate10Label.Text = 'Filter at (sample rate ) / 10';
            app.SpecificationMethodListBoxLabel = uilabel(app.SarkaniRandomTab);
            app.SpecificationMethodListBoxLabel.Text = {'Specification '; 'Method'};
            app.SpecificationMethodListBox = uilistbox(app.SarkaniRandomTab);
            app.SpecificationMethodListBox.Items = {'kurtosis', 'beta & n'};
            app.SpecificationMethodListBox.ValueChangedFcn = createCallbackFcn(app, @SpecificationMethodListBoxValueChanged, true);
            app.SpecificationMethodListBox.Value = 'kurtosis';
            app.betaEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.betaEditField.ValueChangingFcn = createCallbackFcn(app, @betaEditFieldValueChanging, true);
            app.nEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.nEditField.ValueChangingFcn = createCallbackFcn(app, @nEditFieldValueChanging, true);
            app.kurtosisEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.kurtosisEditFieldLabel.Text = 'kurtosis';
            app.kurtosisEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.kurtosisEditField.ValueChangingFcn = createCallbackFcn(app, @kurtosisEditFieldValueChanging, true);
            app.BandlimitviaLowpassFilterListBoxLabel = uilabel(app.SarkaniRandomTab);
            app.BandlimitviaLowpassFilterListBoxLabel.Text = {'Band-limit via '; 'Lowpass Filter'};
            app.BandlimitviaLowpassFilterListBox_Sarkani = uilistbox(app.SarkaniRandomTab);
            app.BandlimitviaLowpassFilterListBox_Sarkani.Items = {'yes', 'no'};
            app.BandlimitviaLowpassFilterListBox_Sarkani.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaLowpassFilterListBox_SarkaniValueChanged, true);
            app.BandlimitviaLowpassFilterListBox_Sarkani.Value = 'yes';
            app.PinkNoiseTab = uitab(app.TabGroup7);
            app.PinkNoiseTab.Title = 'Pink Noise';
            app.CalculateButton_pink = uibutton(app.PinkNoiseTab, 'push');
            app.CalculateButton_pink.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_pinkPushed, true);
            app.CalculateButton_pink.Text = 'Calculate';
            app.OutputNameEditField_2Label_2 = uilabel(app.PinkNoiseTab);
            app.OutputNameEditField_2Label_2.Text = 'Data Output Name';
            app.OutputNameEditField_pink = uieditfield(app.PinkNoiseTab, 'text');
            app.OutputNameEditField_pink.Value = 'pink';
            app.PinknoiseovertheaudiofrequencydomainLabel = uilabel(app.PinkNoiseTab);
            app.PinknoiseovertheaudiofrequencydomainLabel.Text = 'Pink noise over the audio frequency domain';
            app.LapaceTransformListBoxLabel = uilabel(app.PinkNoiseTab);
            app.LapaceTransformListBoxLabel.Text = 'Lapace Transform';
            app.LapaceTransformListBox = uilistbox(app.PinkNoiseTab);
            app.LapaceTransformListBox.Items = {'1 / sqrt(s)', '3 / sqrt( s + 8 pi )'};
            app.LapaceTransformListBox.Value = '3 / sqrt( s + 8 pi )';
            app.OutputNameEditField_2Label_7 = uilabel(app.PinkNoiseTab);
            app.OutputNameEditField_2Label_7.Text = 'Audio Output Name';
            app.AudioOutputNameEditField = uieditfield(app.PinkNoiseTab, 'text');
            app.AudioOutputNameEditField.Value = 'pink_audio.wav';
            app.CalculateButton_pink_2 = uibutton(app.PinkNoiseTab, 'push');
            app.CalculateButton_pink_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_pink_2Pushed, true);
            app.CalculateButton_pink_2.Text = 'Listen to Audio';
            app.ResultsTextAreaLabel_random_sk = uilabel(app.dTab);
            app.ResultsTextAreaLabel_random_sk.Text = 'Results';
            app.ResultsTextArea_random_sk = uitextarea(app.dTab);
            app.DurationsecEditField_2Label_2 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_2.Text = 'Duration (sec)';
            app.DurationsecEditField_sk = uieditfield(app.dTab, 'text');
            app.DurationsecEditField_sk.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_skValueChanging, true);
            app.DurationsecEditField_2Label_3 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_3.Text = 'Std Dev';
            app.StdDevEditField_sk = uieditfield(app.dTab, 'text');
            app.StdDevEditField_sk.ValueChangingFcn = createCallbackFcn(app, @StdDevEditField_skValueChanging, true);
            app.YaxisLabelEditField_2Label_2 = uilabel(app.dTab);
            app.YaxisLabelEditField_2Label_2.Text = 'Y-axis Label';
            app.YaxisLabelEditField_sk = uieditfield(app.dTab, 'text');
            app.YaxisLabelEditField_sk.Value = 'Accel (G)';
            app.DurationsecEditField_2Label_4 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_4.Text = 'Sample Rate (Hz)';
            app.SampleRateEditField_sk = uieditfield(app.dTab, 'text');
            app.SampleRateEditField_sk.ValueChangingFcn = createCallbackFcn(app, @SampleRateEditField_skValueChanging, true);
            app.eTab = uitab(app.GenerateTabGroup);
            app.eTab.Title = 'e';
            app.PulseTypeListBoxLabel = uilabel(app.eTab);
            app.PulseTypeListBoxLabel.Text = 'Pulse Type';
            app.PulseTypeListBox = uilistbox(app.eTab);
            app.PulseTypeListBox.Items = {'Half-Sine', 'Versed Sine', 'Terminal Sawtooth', 'Symmetric Sawtooth', 'Rectangular'};
            app.PulseTypeListBox.ValueChangedFcn = createCallbackFcn(app, @PulseTypeListBoxValueChanged, true);
            app.PulseTypeListBox.Value = 'Half-Sine';
            app.CalculateButton_halfsine = uibutton(app.eTab, 'push');
            app.CalculateButton_halfsine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_halfsinePushed, true);
            app.CalculateButton_halfsine.Text = 'Calculate';
            app.AmplitudeEditField_3Label = uilabel(app.eTab);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_halfsine = uieditfield(app.eTab, 'text');
            app.AmplitudeEditField_halfsine.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_halfsineValueChanging, true);
            app.YaxisLabelEditField_4Label_2 = uilabel(app.eTab);
            app.YaxisLabelEditField_4Label_2.Text = 'Y-axis Label';
            app.YaxisLabelEditField_halfsine = uieditfield(app.eTab, 'text');
            app.YaxisLabelEditField_halfsine.Value = 'Accel (G)';
            app.SampleRateHzEditField_3Label_2 = uilabel(app.eTab);
            app.SampleRateHzEditField_3Label_2.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_halfsine = uieditfield(app.eTab, 'text');
            app.SampleRateHzEditField_halfsine.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_halfsineValueChanging, true);
            app.PrepulseDurationsecEditFieldLabel = uilabel(app.eTab);
            app.PrepulseDurationsecEditFieldLabel.Text = 'Pre-pulse Duration (sec)';
            app.PrepulseDurationsecEditField = uieditfield(app.eTab, 'text');
            app.PrepulseDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @PrepulseDurationsecEditFieldValueChanged, true);
            app.PrepulseDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @PrepulseDurationsecEditFieldValueChanging, true);
            app.PulseDurationsecEditFieldLabel = uilabel(app.eTab);
            app.PulseDurationsecEditFieldLabel.Text = 'Pulse Duration (sec)';
            app.PulseDurationsecEditField = uieditfield(app.eTab, 'text');
            app.PulseDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @PulseDurationsecEditFieldValueChanging, true);
            app.PostPulseDurationsecEditFieldLabel = uilabel(app.eTab);
            app.PostPulseDurationsecEditFieldLabel.Text = 'Post-Pulse Duration (sec)';
            app.PostPulseDurationsecEditField = uieditfield(app.eTab, 'text');
            app.PostPulseDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @PostPulseDurationsecEditFieldValueChanging, true);
            app.OutputArrayNameEditField_2Label_2 = uilabel(app.eTab);
            app.OutputArrayNameEditField_2Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_halfsine = uieditfield(app.eTab, 'text');
            app.OutputArrayNameEditField_halfsine.Value = 'pulse';
            app.fTab = uitab(app.GenerateTabGroup);
            app.fTab.Title = 'f';
            app.FreqHzEditFieldLabel = uilabel(app.fTab);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.fTab, 'text');
            app.FreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanging, true);
            app.AmplitudeEditField_3Label_2 = uilabel(app.fTab);
            app.AmplitudeEditField_3Label_2.Text = 'Amplitude';
            app.AmplitudeEditField_wavelet = uieditfield(app.fTab, 'text');
            app.AmplitudeEditField_wavelet.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_waveletValueChanging, true);
            app.DelayTimesecEditFieldLabel = uilabel(app.fTab);
            app.DelayTimesecEditFieldLabel.Text = 'Delay Time (sec)';
            app.DelayTimesecEditField = uieditfield(app.fTab, 'text');
            app.DelayTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @DelayTimesecEditFieldValueChanging, true);
            app.SampleRateHzEditField_3Label_3 = uilabel(app.fTab);
            app.SampleRateHzEditField_3Label_3.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_wavelet = uieditfield(app.fTab, 'text');
            app.SampleRateHzEditField_wavelet.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_waveletValueChanging, true);
            app.YaxisLabelEditField_4Label_3 = uilabel(app.fTab);
            app.YaxisLabelEditField_4Label_3.Text = 'Y-axis Label';
            app.YaxisLabelEditField_wavelet = uieditfield(app.fTab, 'text');
            app.YaxisLabelEditField_wavelet.ValueChangingFcn = createCallbackFcn(app, @YaxisLabelEditField_waveletValueChanging, true);
            app.YaxisLabelEditField_wavelet.Value = 'Accel (G)';
            app.CalculateButton_wavelet = uibutton(app.fTab, 'push');
            app.CalculateButton_wavelet.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_waveletPushed, true);
            app.CalculateButton_wavelet.Text = 'Calculate';
            app.WaveletLabel = uilabel(app.fTab);
            app.WaveletLabel.Text = 'Wavelet';
            app.EndTimesecEditFieldLabel = uilabel(app.fTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.fTab, 'text');
            app.EndTimesecEditField.ValueChangingFcn = createCallbackFcn(app, @EndTimesecEditFieldValueChanging, true);
            app.OutputArrayNameEditField_3Label = uilabel(app.fTab);
            app.OutputArrayNameEditField_3Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_wavelet = uieditfield(app.fTab, 'text');
            app.OutputArrayNameEditField_wavelet.Value = 'wavelet';
            app.NumberofHalfSinesLabel = uilabel(app.fTab);
            app.NumberofHalfSinesLabel.Text = {'Number of '; 'Half-Sines'};
            app.NumberofHalfSinesListBox = uilistbox(app.fTab);
            app.NumberofHalfSinesListBox.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', '27', '29', '31', '33', '35', '37', '39', '41', '43', '45', '47', '49', '51', '53', '55', '57', '59', '61', '63'};
            app.NumberofHalfSinesListBox.Value = '3';
            app.gTab = uitab(app.GenerateTabGroup);
            app.gTab.Title = 'g';
            app.IEEEstd693SineBeatLabel = uilabel(app.gTab);
            app.IEEEstd693SineBeatLabel.Text = 'IEEE std 693 Sine Beat';
            app.SuggestextrahalfcycleforzeronetdisplacementLabel = uilabel(app.gTab);
            app.SuggestextrahalfcycleforzeronetdisplacementLabel.Text = '(Suggest extra half-cycle for zero net displacement)';
            app.AccelerationListBoxLabel = uilabel(app.gTab);
            app.AccelerationListBoxLabel.Text = 'Acceleration';
            app.AccelerationListBox = uilistbox(app.gTab);
            app.AccelerationListBox.Items = {'High PL  0.6 G', 'High  0.5 G     ', 'Moderate PL  0.3 G', 'Moderate  0.25 G', 'Other           '};
            app.AccelerationListBox.ValueChangedFcn = createCallbackFcn(app, @AccelerationListBoxValueChanged, true);
            app.AccelerationListBox.Value = 'High PL  0.6 G';
            app.AccelerationGEditFieldLabel = uilabel(app.gTab);
            app.AccelerationGEditFieldLabel.Text = 'Acceleration (G)';
            app.AccelerationGEditField = uieditfield(app.gTab, 'text');
            app.FrequencyHzEditField_3Label_2 = uilabel(app.gTab);
            app.FrequencyHzEditField_3Label_2.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_IEEE = uieditfield(app.gTab, 'text');
            app.PausesecEditFieldLabel = uilabel(app.gTab);
            app.PausesecEditFieldLabel.Text = 'Pause (sec)';
            app.PausesecEditField = uieditfield(app.gTab, 'text');
            app.PausesecEditField.Value = '2';
            app.NumberofCyclesEditFieldLabel = uilabel(app.gTab);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles';
            app.NumberofCyclesEditField = uieditfield(app.gTab, 'text');
            app.NumberofCyclesEditField.Value = '10.5';
            app.NumberofBeatsEditFieldLabel = uilabel(app.gTab);
            app.NumberofBeatsEditFieldLabel.Text = 'Number of Beats';
            app.NumberofBeatsEditField = uieditfield(app.gTab, 'text');
            app.NumberofBeatsEditField.Value = '5';
            app.PreshockDurationsecEditFieldLabel = uilabel(app.gTab);
            app.PreshockDurationsecEditFieldLabel.Text = 'Preshock Duration (sec)';
            app.PreshockDurationsecEditField = uieditfield(app.gTab, 'text');
            app.PreshockDurationsecEditField.Value = '1';
            app.SelectUnitListBoxLabel = uilabel(app.gTab);
            app.SelectUnitListBoxLabel.Text = 'Select Unit';
            app.SelectUnitListBox = uilistbox(app.gTab);
            app.SelectUnitListBox.Items = {'G, in/sec, in      ', 'G, cm/sec, mm'};
            app.SelectUnitListBox.Value = 'G, in/sec, in      ';
            app.CalculateButton_IEEE = uibutton(app.gTab, 'push');
            app.CalculateButton_IEEE.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_IEEEPushed, true);
            app.CalculateButton_IEEE.Text = 'Calculate';
            app.hTab = uitab(app.GenerateTabGroup);
            app.hTab.Title = 'h';
            app.StudentstdistributionLabel = uilabel(app.hTab);
            app.StudentstdistributionLabel.Text = 'Students t distribution';
            app.CalculateButton_student = uibutton(app.hTab, 'push');
            app.CalculateButton_student.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_studentPushed, true);
            app.CalculateButton_student.Text = 'Calculate';
            app.OutputArrayNameEditField_3Label_2 = uilabel(app.hTab);
            app.OutputArrayNameEditField_3Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_student = uieditfield(app.hTab, 'text');
            app.OutputArrayNameEditField_student.Value = 'student';
            app.ResultsTextAreaLabel_student = uilabel(app.hTab);
            app.ResultsTextAreaLabel_student.Text = 'Results';
            app.ResultsTextArea_student = uitextarea(app.hTab);
            app.StdDevEditField_3Label = uilabel(app.hTab);
            app.StdDevEditField_3Label.Text = 'Std Dev';
            app.StdDevEditField_student = uieditfield(app.hTab, 'text');
            app.StdDevEditField_student.ValueChangingFcn = createCallbackFcn(app, @StdDevEditField_studentValueChanging, true);
            app.DurationsecEditField_5Label_4 = uilabel(app.hTab);
            app.DurationsecEditField_5Label_4.Text = 'Duration (sec)';
            app.DurationsecEditField_student = uieditfield(app.hTab, 'text');
            app.DurationsecEditField_student.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_studentValueChanging, true);
            app.SampleRateHzEditField_4Label_2 = uilabel(app.hTab);
            app.SampleRateHzEditField_4Label_2.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_student = uieditfield(app.hTab, 'text');
            app.SampleRateHzEditField_student.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_studentValueChanging, true);
            app.Enterdofrealnumber4Label = uilabel(app.hTab);
            app.Enterdofrealnumber4Label.Text = {'Enter dof'; 'real number > 4'};
            app.Enterdofrealnumber4EditField = uieditfield(app.hTab, 'text');
            app.Enterdofrealnumber4EditField.ValueChangingFcn = createCallbackFcn(app, @Enterdofrealnumber4EditFieldValueChanging, true);
            app.AmplitudeLabelEditFieldLabel = uilabel(app.hTab);
            app.AmplitudeLabelEditFieldLabel.Text = 'Amplitude Label';
            app.AmplitudeLabelEditField = uieditfield(app.hTab, 'text');
            app.AmplitudeLabelEditField.Value = 'Accel (G)';
            app.MethodListBoxLabel = uilabel(app.hTab);
            app.MethodListBoxLabel.Text = 'Method';
            app.MethodListBox = uilistbox(app.hTab);
            app.MethodListBox.Items = {'degrees-of-freedom', 'kurtosis'};
            app.MethodListBox.ValueChangedFcn = createCallbackFcn(app, @MethodListBoxValueChanged, true);
            app.MethodListBox.Value = 'degrees-of-freedom';
            app.Enterkurtosisrealnumber3EditFieldLabel = uilabel(app.hTab);

            app.Enterkurtosisrealnumber3EditFieldLabel.Text = {'Enter kurtosis'; 'real number > 3'};
            app.Enterkurtosisrealnumber3EditField = uieditfield(app.hTab, 'text');
            app.Label_5 = uilabel(app.hTab);
            app.Label_5.Text = {'The t-distribution is symmetric and bell-shaped, like the normal '; 'distribution. However, the t-distribution has heavier tails, meaning '; 'that it is more prone to producing values that fall far from its mean.'};
            app.iTab = uitab(app.GenerateTabGroup);
            app.iTab.Title = 'i';
            app.AddsinetonestoexistingsignalLabel = uilabel(app.iTab);
            app.AddsinetonestoexistingsignalLabel.Text = 'Add sine tones to existing signal';
            app.CalculateButton_add_sine = uibutton(app.iTab, 'push');
            app.CalculateButton_add_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_add_sinePushed, true);
            app.CalculateButton_add_sine.Text = 'Calculate';
            app.text1_2 = uilabel(app.iTab);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.listbox_method_add_sine = uilistbox(app.iTab);
            app.listbox_method_add_sine.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_add_sine.ValueChangedFcn = createCallbackFcn(app, @listbox_method_add_sineValueChanged, true);
            app.listbox_method_add_sine.Tag = 'listbox_method';
            app.listbox_method_add_sine.Value = 'Array Preloaded in Matlab';
            app.UpdateListButton_add_sine = uibutton(app.iTab, 'push');
            app.UpdateListButton_add_sine.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_add_sinePushed, true);
            app.UpdateListButton_add_sine.Text = 'Update List';
            app.SelectInputArrayListBoxLabel_2 = uilabel(app.iTab);
            app.SelectInputArrayListBoxLabel_2.Text = 'Select Input Array';
            app.SelectInputArrayListBox_add_sine = uilistbox(app.iTab);
            app.SelectInputArrayListBox_add_sine.Items = {'Item 1'};
            app.VelocityDisplacementCorrectionLabel = uilabel(app.iTab);
            app.VelocityDisplacementCorrectionLabel.Text = {'Velocity & Displacement '; 'Correction'};
            app.VelocityDisplacementCorrectionListBox = uilistbox(app.iTab);
            app.VelocityDisplacementCorrectionListBox.Items = {'yes', 'no'};
            app.VelocityDisplacementCorrectionListBox.ValueChangedFcn = createCallbackFcn(app, @VelocityDisplacementCorrectionListBoxValueChanged, true);
            app.VelocityDisplacementCorrectionListBox.Value = 'yes';
            app.AmplitudeTypeLabel = uilabel(app.iTab);
            app.AmplitudeTypeLabel.Text = 'Amplitude Type';
            app.AmplitudeTypeListBox = uilistbox(app.iTab);
            app.AmplitudeTypeListBox.Items = {'Acceleration', 'Other'};
            app.AmplitudeTypeListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeTypeListBoxValueChanged, true);
            app.AmplitudeTypeListBox.Value = 'Acceleration';
            app.NumberofSinesListBoxLabel = uilabel(app.iTab);
            app.NumberofSinesListBoxLabel.Text = {'Number '; 'of Sines'};
            app.NumberofSinesListBox = uilistbox(app.iTab);
            app.NumberofSinesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofSinesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofSinesListBoxValueChanged, true);
            app.NumberofSinesListBox.Value = '1';
            app.OutputArrayNameEditField_3Label_3 = uilabel(app.iTab);
            app.OutputArrayNameEditField_3Label_3.Text = 'Output Array Name';
            app.OutputArrayNameEditField_add_sine = uieditfield(app.iTab, 'text');
            app.OutputArrayNameEditField_add_sine.Value = 'signal_plus_sines';
            app.ResultsTextAreaLabel_add_sine = uilabel(app.iTab);
            app.ResultsTextAreaLabel_add_sine.Text = 'Results';
            app.ResultsTextArea_add_sine = uitextarea(app.iTab);
            app.UITable_add_sine = uitable(app.iTab);
            app.UITable_add_sine.ColumnName = {'Amplitude'; 'Freq (Hz)'; 'Phase (deg)'};
            app.UITable_add_sine.RowName = {};
            app.UITable_add_sine.ColumnEditable = true;
            app.UITable_add_sine.CellEditCallback = createCallbackFcn(app, @UITable_add_sineCellEdit, true);
            app.UITable_add_sine.SelectionChangedFcn = createCallbackFcn(app, @UITable_add_sineSelectionChanged, true);
            app.UITable_add_sine.KeyPressFcn = createCallbackFcn(app, @UITable_add_sineKeyPress, true);
            app.OutputArrayNameEditField_3Label_4 = uilabel(app.iTab);
            app.OutputArrayNameEditField_3Label_4.Text = 'Y-axis Label';
            app.YSEditField_add_Sine = uieditfield(app.iTab, 'text');
            app.YSEditField_add_Sine.Value = 'Accel (G)';
            app.SelectUnitListBox_2Label = uilabel(app.iTab);
            app.SelectUnitListBox_2Label.Text = 'Select Unit';
            app.SelectUnitListBox_add_sine = uilistbox(app.iTab);
            app.SelectUnitListBox_add_sine.Items = {'G, in/sec, in      ', 'G, cm/sec, mm'};
            app.SelectUnitListBox_add_sine.Value = 'G, in/sec, in      ';
            app.HighPassFreqHzEditFieldLabel = uilabel(app.iTab);
            app.HighPassFreqHzEditFieldLabel.Text = 'High Pass Freq (Hz)';
            app.HighPassFreqHzEditField = uieditfield(app.iTab, 'text');
            app.HighPassFreqHzEditField.Value = '5';
            app.jTab = uitab(app.GenerateTabGroup);
            app.jTab.Title = 'j';
            app.HALTHASSsimulationLabel = uilabel(app.jTab);
            app.HALTHASSsimulationLabel.Text = 'HALT/HASS simulation';
            app.GotoHALTHASSButton = uibutton(app.jTab, 'push');
            app.GotoHALTHASSButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHALTHASSButtonPushed, true);
            app.GotoHALTHASSButton.Text = 'Go to HALT/HASS';
            app.kTab = uitab(app.GenerateTabGroup);
            app.kTab.Title = 'k';
            app.ChirpLabel = uilabel(app.kTab);
            app.ChirpLabel.Text = 'Chirp';
            app.CalculateButton_chirp = uibutton(app.kTab, 'push');
            app.CalculateButton_chirp.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_chirpPushed, true);
            app.CalculateButton_chirp.Text = 'Calculate';
            app.StartFreqHzEditFieldLabel = uilabel(app.kTab);
            app.StartFreqHzEditFieldLabel.Text = 'Start Freq (Hz)';
            app.StartFreqHzEditField = uieditfield(app.kTab, 'text');
            app.StartFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqHzEditFieldValueChanged, true);
            app.StartFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @StartFreqHzEditFieldValueChanging, true);
            app.DurationsecEditField_5Label_5 = uilabel(app.kTab);
            app.DurationsecEditField_5Label_5.Text = 'Duration (sec)';
            app.DurationsecEditField_chirp = uieditfield(app.kTab, 'text');
            app.DurationsecEditField_chirp.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_chirpValueChanging, true);
            app.PhaseradEditFieldLabel = uilabel(app.kTab);
            app.PhaseradEditFieldLabel.Text = 'Phase (rad)';
            app.PhaseradEditField = uieditfield(app.kTab, 'text');
            app.PhaseradEditField.ValueChangingFcn = createCallbackFcn(app, @PhaseradEditFieldValueChanging, true);
            app.PhaseradEditField.Value = '0';
            app.EndFreqHzEditFieldLabel = uilabel(app.kTab);
            app.EndFreqHzEditFieldLabel.Text = 'End Freq (Hz)';
            app.EndFreqHzEditField = uieditfield(app.kTab, 'text');
            app.EndFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @EndFreqHzEditFieldValueChanging, true);
            app.SampleRateHzEditField_4Label_3 = uilabel(app.kTab);
            app.SampleRateHzEditField_4Label_3.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_chirp = uieditfield(app.kTab, 'text');
            app.SampleRateHzEditField_chirp.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_chirpValueChanging, true);
            app.YaxisLabelEditField_4Label_4 = uilabel(app.kTab);
            app.YaxisLabelEditField_4Label_4.Text = 'Y-axis Label';
            app.YaxisLabelEditField_chirp = uieditfield(app.kTab, 'text');
            app.YaxisLabelEditField_chirp.Value = 'Accel (G)';
            app.AmplitudeEditField_3Label_3 = uilabel(app.kTab);
            app.AmplitudeEditField_3Label_3.Text = 'Amplitude';
            app.AmplitudeEditField_chirp = uieditfield(app.kTab, 'text');
            app.AmplitudeEditField_chirp.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_chirpValueChanging, true);
            app.SweepTypeListBox_2Label = uilabel(app.kTab);
            app.SweepTypeListBox_2Label.Text = 'Sweep Type';
            app.SweepTypeListBox_chirp = uilistbox(app.kTab);
            app.SweepTypeListBox_chirp.Items = {'Linear', 'Logarithmic'};
            app.SweepTypeListBox_chirp.ValueChangedFcn = createCallbackFcn(app, @SweepTypeListBox_chirpValueChanged, true);
            app.SweepTypeListBox_chirp.Value = 'Logarithmic';
            app.OutputArrayNameEditField_5Label = uilabel(app.kTab);
            app.OutputArrayNameEditField_5Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_chirp = uieditfield(app.kTab, 'text');
            app.OutputArrayNameEditField_chirp.Value = 'chirp';
            app.lTab = uitab(app.GenerateTabGroup);
            app.lTab.Title = 'l';
            app.CalculateButton_unit_impulse = uibutton(app.lTab, 'push');
            app.CalculateButton_unit_impulse.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_unit_impulsePushed, true);
            app.CalculateButton_unit_impulse.Text = 'Calculate';
            app.DurationsecEditField_5Label_2 = uilabel(app.lTab);
            app.DurationsecEditField_5Label_2.Text = 'Duration (sec)';
            app.DurationsecEditField_unit_impulse = uieditfield(app.lTab, 'text');
            app.DurationsecEditField_unit_impulse.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_unit_impulseValueChanging, true);
            app.SampleRateHzEditField_3Label = uilabel(app.lTab);
            app.SampleRateHzEditField_3Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_unit_impulse = uieditfield(app.lTab, 'text');
            app.SampleRateHzEditField_unit_impulse.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_unit_impulseValueChanging, true);
            app.OutputArrayNameEditField_2Label = uilabel(app.lTab);
            app.OutputArrayNameEditField_2Label.Text = 'Output Array Name';
            app.OutputArrayNameEditField_unit_impulse = uieditfield(app.lTab, 'text');
            app.OutputArrayNameEditField_unit_impulse.Value = 'unit_impulse';
            app.UnitImpulseFunctionLabel = uilabel(app.lTab);
            app.UnitImpulseFunctionLabel.Text = 'Unit Impulse Function';
            app.YaxisLabelEditField_4Label = uilabel(app.lTab);
            app.YaxisLabelEditField_4Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField_unit_impulse = uieditfield(app.lTab, 'text');
            app.YaxisLabelEditField_unit_impulse.Value = 'Accel (G)';
            app.mTab = uitab(app.GenerateTabGroup);
            app.mTab.Title = 'm';
            app.SinemultiplecolumnsLabel = uilabel(app.mTab);
            app.SinemultiplecolumnsLabel.Text = 'Sine, multiple columns';
            app.GotoSinemultiplecolumnsButton = uibutton(app.mTab, 'push');
            app.GotoSinemultiplecolumnsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSinemultiplecolumnsButtonPushed, true);
            app.GotoSinemultiplecolumnsButton.Text = 'Go to Sine, multiple columns';
            app.nTab = uitab(app.GenerateTabGroup);
            app.nTab.Title = 'n';
            app.MultipleSineSweepsLabel = uilabel(app.nTab);
            app.MultipleSineSweepsLabel.Text = 'Multiple Sine Sweeps';
            app.CalculateButton_multiple_sine = uibutton(app.nTab, 'push');
            app.CalculateButton_multiple_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_multiple_sinePushed, true);
            app.CalculateButton_multiple_sine.Text = 'Calculate';
            app.NumberofSweepSetsLabel = uilabel(app.nTab);
            app.NumberofSweepSetsLabel.Text = {'Number of '; 'Sweep Sets'};
            app.NumberofSweepSetsListBox = uilistbox(app.nTab);
            app.NumberofSweepSetsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.NumberofSweepSetsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofSweepSetsListBoxValueChanged, true);
            app.NumberofSweepSetsListBox.Value = '1';
            app.ResetButton = uibutton(app.nTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DeleteRowBelowButton = uibutton(app.nTab, 'push');
            app.DeleteRowBelowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowBelowButtonPushed, true);
            app.DeleteRowBelowButton.Text = 'Delete Row Below';
            app.RateListBoxLabel = uilabel(app.nTab);
            app.RateListBoxLabel.Text = 'Rate';
            app.RateListBox = uilistbox(app.nTab);
            app.RateListBox.Items = {'Linear', 'Log'};
            app.RateListBox.Value = 'Linear';
            app.RowListBoxLabel = uilabel(app.nTab);
            app.RowListBoxLabel.Text = 'Row';
            app.RowListBox = uilistbox(app.nTab);
            app.RowListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.RowListBox.Value = '1';
            app.DurationsecEditField_6Label = uilabel(app.nTab);
            app.DurationsecEditField_6Label.Text = 'Duration (sec)';
            app.DurationsecEditField_msweep = uieditfield(app.nTab, 'text');
            app.SampleRateHzEditField_5Label = uilabel(app.nTab);
            app.SampleRateHzEditField_5Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_msweep = uieditfield(app.nTab, 'text');
            app.OutputTimeHistoryNameEditFieldLabel = uilabel(app.nTab);
            app.OutputTimeHistoryNameEditFieldLabel.Text = 'Output Time History Name';
            app.OutputTimeHistoryNameEditField = uieditfield(app.nTab, 'text');
            app.OutputTimeHistoryNameEditField.Value = 'multiple_sweeps';
            app.SaveModelNameEditFieldLabel = uilabel(app.nTab);
            app.SaveModelNameEditFieldLabel.Text = 'Save Model Name';
            app.SaveModelNameEditField = uieditfield(app.nTab, 'text');
            app.SaveModelNameEditField.Value = 'multiple_sweeps_model';
            app.CalculateButton_load_msweep = uibutton(app.nTab, 'push');
            app.CalculateButton_load_msweep.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_load_msweepPushed, true);
            app.CalculateButton_load_msweep.Text = 'Load Model File';
            app.CalculateButton_msweep = uibutton(app.nTab, 'push');
            app.CalculateButton_msweep.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_msweepPushed, true);
            app.CalculateButton_msweep.Text = 'Save Model File';
            app.UITable_msweep = uitable(app.nTab);
            app.UITable_msweep.ColumnName = {'N'; 'Start Freq (Hz)'; 'End Freq (Hz)'; 'Accel (G)'};
            app.UITable_msweep.RowName = {};
            app.UITable_msweep.ColumnEditable = [false true true true];
            app.SweepParametersLabel = uilabel(app.nTab);
            app.SweepParametersLabel.Text = 'Sweep Parameters';
            app.oTab = uitab(app.GenerateTabGroup);
            app.oTab.Title = 'o';
            app.GenerateseriesofintermittenthalfsinepulsesLabel = uilabel(app.oTab);
            app.GenerateseriesofintermittenthalfsinepulsesLabel.Text = 'Generate series of intermittent half-sine pulses.  Modal impact force simulation.';
            app.CalculateButton_half_sine_series = uibutton(app.oTab, 'push');
            app.CalculateButton_half_sine_series.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_half_sine_seriesPushed, true);
            app.CalculateButton_half_sine_series.Text = 'Calculate';
            app.NumberofPulsesEditFieldLabel = uilabel(app.oTab);
            app.NumberofPulsesEditFieldLabel.Text = 'Number of Pulses';
            app.NumberofPulsesEditField = uieditfield(app.oTab, 'text');
            app.NumberofPulsesEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofPulsesEditFieldValueChanging, true);
            app.NumberofPulsesEditField.Value = '10';
            app.NominalAmplitudeEditFieldLabel = uilabel(app.oTab);
            app.NominalAmplitudeEditFieldLabel.Text = 'Nominal Amplitude';
            app.NominalAmplitudeEditField = uieditfield(app.oTab, 'text');
            app.NominalAmplitudeEditField.ValueChangingFcn = createCallbackFcn(app, @NominalAmplitudeEditFieldValueChanging, true);
            app.NominalAmplitudeEditField.Value = '10';
            app.PrePulseDurationsecEditFieldLabel = uilabel(app.oTab);
            app.PrePulseDurationsecEditFieldLabel.Text = 'Pre-Pulse Duration (sec)';
            app.PrePulseDurationsecEditField = uieditfield(app.oTab, 'text');
            app.PrePulseDurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @PrePulseDurationsecEditFieldValueChanging, true);
            app.PrePulseDurationsecEditField.Value = '2';
            app.OutputArrayPrefixEditFieldLabel = uilabel(app.oTab);
            app.OutputArrayPrefixEditFieldLabel.Text = 'Output Array Prefix';
            app.OutputArrayPrefixEditField = uieditfield(app.oTab, 'text');
            app.OutputArrayPrefixEditField.Value = 'force';
            app.MaxEditFieldLabel = uilabel(app.oTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.oTab, 'text');
            app.MaxEditField.ValueChangingFcn = createCallbackFcn(app, @MaxEditFieldValueChanging, true);
            app.MaxEditField.Value = '3';
            app.MinEditFieldLabel = uilabel(app.oTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.oTab, 'text');
            app.MinEditField.ValueChangingFcn = createCallbackFcn(app, @MinEditFieldValueChanging, true);
            app.MinEditField.Value = '2.5';
            app.TimebetweenPulsessecLabel = uilabel(app.oTab);
            app.TimebetweenPulsessecLabel.Text = 'Time between Pulses (sec)';
            app.SampleRateHzEditField_4Label_4 = uilabel(app.oTab);
            app.SampleRateHzEditField_4Label_4.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_ihs = uieditfield(app.oTab, 'text');
            app.SampleRateHzEditField_ihs.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_ihsValueChanging, true);
            app.SampleRateHzEditField_ihs.Value = '40000';
            app.YaxisLabelEditField_5Label = uilabel(app.oTab);
            app.YaxisLabelEditField_5Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField_ihs = uieditfield(app.oTab, 'text');
            app.YaxisLabelEditField_ihs.Value = 'Force (lbf)';
            app.HalfSinePulseDurationmsecLabel = uilabel(app.oTab);
            app.HalfSinePulseDurationmsecLabel.Text = 'Half-Sine Pulse Duration (msec)';
            app.MaxEditField_2Label = uilabel(app.oTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_ihs = uieditfield(app.oTab, 'text');
            app.MaxEditField_ihs.ValueChangingFcn = createCallbackFcn(app, @MaxEditField_ihsValueChanging, true);
            app.MaxEditField_ihs.Value = '2';
            app.MinEditField_2Label = uilabel(app.oTab);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_ihs = uieditfield(app.oTab, 'text');
            app.MinEditField_ihs.ValueChangingFcn = createCallbackFcn(app, @MinEditField_ihsValueChanging, true);
            app.MinEditField_ihs.Value = '1';
            app.RandomNumberSeedListBox_3Label = uilabel(app.oTab);
            app.RandomNumberSeedListBox_3Label.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox_ihs = uilistbox(app.oTab);
            app.RandomNumberSeedListBox_ihs.Items = {'1', 'None'};
            app.RandomNumberSeedListBox_ihs.Value = '1';
            app.StructuralDynamicsTab = uitab(app.TabGroup);
            app.StructuralDynamicsTab.Title = 'Structural Dynamics';
            app.TabGroup_2 = uitabgroup(app.StructuralDynamicsTab);
            app.SpringMassSystemsTab = uitab(app.TabGroup_2);
            app.SpringMassSystemsTab.Title = 'Spring-Mass Systems';
            app.SelectNumberofDOFListBoxLabel = uilabel(app.SpringMassSystemsTab);
            app.SelectNumberofDOFListBoxLabel.Text = 'Select Number of DOF';
            app.SelectNumberofDOFListBox = uilistbox(app.SpringMassSystemsTab);
            app.SelectNumberofDOFListBox.Items = {'Single', 'Two', 'Three', 'Six', 'Multiple'};
            app.SelectNumberofDOFListBox.ValueChangedFcn = createCallbackFcn(app, @SelectNumberofDOFListBoxValueChanged, true);
            app.SelectNumberofDOFListBox.Value = 'Single';
            app.SelectSystemListBoxLabel = uilabel(app.SpringMassSystemsTab);
            app.SelectSystemListBoxLabel.Text = 'Select System';
            app.SelectSystemListBox = uilistbox(app.SpringMassSystemsTab);
            app.SelectSystemListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton = uibutton(app.SpringMassSystemsTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StructuresSystemsTab = uitab(app.TabGroup_2);
            app.StructuresSystemsTab.Title = 'Structures & Systems';
            app.TabGroup3 = uitabgroup(app.StructuresSystemsTab);
            app.BeamsTab = uitab(app.TabGroup3);
            app.BeamsTab.Title = 'Beams';
            app.TabGroup4 = uitabgroup(app.BeamsTab);
            app.BeamClassicalTab = uitab(app.TabGroup4);
            app.BeamClassicalTab.Title = 'Beam Classical';
            app.AnalyzeButtonStructures_BeamClassical = uibutton(app.BeamClassicalTab, 'push');
            app.AnalyzeButtonStructures_BeamClassical.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_BeamClassicalPushed, true);
            app.AnalyzeButtonStructures_BeamClassical.Text = 'Analyze Beam Classical';
            app.SelectBeamClassicalListBoxLabel = uilabel(app.BeamClassicalTab);
            app.SelectBeamClassicalListBoxLabel.Text = 'Select Beam Classical';
            app.SelectBeamClassicalListBox = uilistbox(app.BeamClassicalTab);
            app.SelectBeamClassicalListBox.Items = {'General Beam Bending, Homogeneous Material', 'General Beam Bending, Composite Laminate', 'Cantilever Beam with Point Mass', 'Cantilever Beam with Applied Force at Free End', 'Simply-Supported Beam with Point Mass', 'Timoshenko Beam', 'Multispan Beam'};
            app.SelectBeamClassicalListBox.Value = 'General Beam Bending, Homogeneous Material';
            app.BeamFEATab = uitab(app.TabGroup4);
            app.BeamFEATab.Title = 'Beam FEA';
            app.AnalyzeButtonStructures_BeamFEA = uibutton(app.BeamFEATab, 'push');
            app.AnalyzeButtonStructures_BeamFEA.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_BeamFEAPushed, true);
            app.AnalyzeButtonStructures_BeamFEA.Text = 'Analyze Beam FEA';
            app.SelectBeamFEAListBoxLabel = uilabel(app.BeamFEATab);
            app.SelectBeamFEAListBoxLabel.Text = 'Select Beam FEA';
            app.SelectBeamFEAListBox = uilistbox(app.BeamFEATab);
            app.SelectBeamFEAListBox.Items = {'Beam Bending with Base Excitation & Applied Force Options', 'Beam with End Springs', 'Rotating Beam', 'Beam Bending Wavelength & Number of Elements', 'Beam-Column', 'Beam-Column, Free-Free, Static Load, Inertia Relief, 3 dof per node', 'Beam-Column, Free-Free, Static Load, Inertia Relief, 6 dof per node', 'Multispan Beam Bending', 'Multispan Beam with Bends Bending'};
            app.SelectBeamFEAListBox.Value = 'Beam Bending with Base Excitation & Applied Force Options';
            app.PlatesTab = uitab(app.TabGroup3);
            app.PlatesTab.Title = 'Plates';
            app.TabGroup5 = uitabgroup(app.PlatesTab);
            app.RectangularPlateBendingTab = uitab(app.TabGroup5);
            app.RectangularPlateBendingTab.Title = 'Rectangular Plate Bending';
            app.SelectRectangularPlateLabel = uilabel(app.RectangularPlateBendingTab);
            app.SelectRectangularPlateLabel.Text = 'Select Rectangular Plate';
            app.SelectRectangularPlateListBox = uilistbox(app.RectangularPlateBendingTab);
            app.SelectRectangularPlateListBox.Items = {'Common BCs & Response', 'Baffled, Uniform Pressure', 'Baffled, Uniform Pressure, Large Deflection', 'Finite Element Method - Thin Plate', 'Finite Element Method - Thick Plate'};
            app.SelectRectangularPlateListBox.Value = 'Common BCs & Response';
            app.AnalyzeButtonStructures_RectangularPlateBending = uibutton(app.RectangularPlateBendingTab, 'push');
            app.AnalyzeButtonStructures_RectangularPlateBending.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_RectangularPlateBendingPushed, true);
            app.AnalyzeButtonStructures_RectangularPlateBending.Text = 'Analyze Rectangular Plate';
            app.CircularPlateBendingTab = uitab(app.TabGroup5);
            app.CircularPlateBendingTab.Title = 'Circular Plate Bending';
            app.SelectPlateTypeListBoxLabel = uilabel(app.CircularPlateBendingTab);
            app.SelectPlateTypeListBoxLabel.Text = 'Select Plate Type';
            app.SelectPlateTypeListBox = uilistbox(app.CircularPlateBendingTab);
            app.SelectPlateTypeListBox.Items = {'Circular Plate', 'Annular Plate'};
            app.SelectPlateTypeListBox.Value = 'Circular Plate';
            app.AnalyzeButtonStructures_CircularPlate = uibutton(app.CircularPlateBendingTab, 'push');
            app.AnalyzeButtonStructures_CircularPlate.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_CircularPlatePushed, true);
            app.AnalyzeButtonStructures_CircularPlate.Text = 'Analyze Plate';
            app.PlateInPlaneTab = uitab(app.TabGroup5);
            app.PlateInPlaneTab.Title = 'Plate, In-Plane';
            app.SelectPlateListBoxLabel = uilabel(app.PlateInPlaneTab);
            app.SelectPlateListBoxLabel.Text = 'Select Plate';
            app.SelectPlateListBox = uilistbox(app.PlateInPlaneTab);
            app.SelectPlateListBox.Items = {'Rectangular Plate, In-Plane'};
            app.SelectPlateListBox.Value = 'Rectangular Plate, In-Plane';
            app.AnalyzeButtonStructures_PlateInPlane = uibutton(app.PlateInPlaneTab, 'push');
            app.AnalyzeButtonStructures_PlateInPlane.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_PlateInPlanePushed, true);
            app.AnalyzeButtonStructures_PlateInPlane.Text = 'Analyze In-Plane';
            app.RingsCylindricalShellsTab = uitab(app.TabGroup3);
            app.RingsCylindricalShellsTab.Title = 'Rings & Cylindrical Shells';
            app.SelectStructureListBoxLabel = uilabel(app.RingsCylindricalShellsTab);
            app.SelectStructureListBoxLabel.Text = 'Select Structure';
            app.SelectStructureListBox = uilistbox(app.RingsCylindricalShellsTab);
            app.SelectStructureListBox.Items = {'Thin Ring', 'Cylindrical Shell, Wave Model', 'Cylindrical Shell, Ring Frequency'};
            app.SelectStructureListBox.Value = 'Thin Ring';
            app.AnalyzeButtonStructures_CylindricalShell = uibutton(app.RingsCylindricalShellsTab, 'push');
            app.AnalyzeButtonStructures_CylindricalShell.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_CylindricalShellPushed, true);
            app.AnalyzeButtonStructures_CylindricalShell.Text = 'Analyze Structure';
            app.RodsShaftsTab = uitab(app.TabGroup3);
            app.RodsShaftsTab.Title = 'Rods & Shafts';
            app.SelectRodAnalysisListBoxLabel = uilabel(app.RodsShaftsTab);
            app.SelectRodAnalysisListBoxLabel.Text = 'Select Rod Analysis';
            app.SelectRodAnalysisListBox = uilistbox(app.RodsShaftsTab);
            app.SelectRodAnalysisListBox.Items = {'Longitudinal', 'Longitudinal, Straight or Tapered Rod FEA', 'Torsional', 'Torsional, Holzer'};
            app.SelectRodAnalysisListBox.Value = 'Longitudinal';
            app.AnalyzeButtonStructures_Rod = uibutton(app.RodsShaftsTab, 'push');
            app.AnalyzeButtonStructures_Rod.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_RodPushed, true);
            app.AnalyzeButtonStructures_Rod.Text = 'Analyze Rod';
            app.MiscellaneousTab_Structures = uitab(app.TabGroup3);
            app.MiscellaneousTab_Structures.Title = 'Miscellaneous';
            app.SelectMiscellaneousListBoxLabel = uilabel(app.MiscellaneousTab_Structures);
            app.SelectMiscellaneousListBoxLabel.Text = 'Select Miscellaneous';
            app.SelectMiscellaneousListBox = uilistbox(app.MiscellaneousTab_Structures);
            app.SelectMiscellaneousListBox.Items = {'Tall Building'};
            app.SelectMiscellaneousListBox.Value = 'Tall Building';
            app.AnalyzeButtonStructures_Misc = uibutton(app.MiscellaneousTab_Structures, 'push');
            app.AnalyzeButtonStructures_Misc.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonStructures_MiscPushed, true);
            app.AnalyzeButtonStructures_Misc.Text = 'Analyze Misc';
            app.EigenvalueProblemsTab = uitab(app.TabGroup_2);
            app.EigenvalueProblemsTab.Title = 'Eigenvalue Problems';
            app.AnalyzeButtonEigen = uibutton(app.EigenvalueProblemsTab, 'push');
            app.AnalyzeButtonEigen.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonEigenPushed, true);
            app.AnalyzeButtonEigen.Text = 'Analyze';
            app.SelectAnalysisListBoxLabel_2 = uilabel(app.EigenvalueProblemsTab);
            app.SelectAnalysisListBoxLabel_2.Text = 'Select Analysis';
            app.SelectAnalysisEigenListBox = uilistbox(app.EigenvalueProblemsTab);
            app.SelectAnalysisEigenListBox.Items = {'Generalized Eigenvalue Problem ', 'Standard Eigenvalue Problem', 'Convert Generalized Eigenvalue Problem to Standard Form', 'Convert Eigenvalue Problem to Tridiagonal Form', 'Modified Gram-Schmidt Orthogonalization', ''};
            app.SelectAnalysisEigenListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonEigenPushed, true);
            app.SelectAnalysisEigenListBox.Value = 'Generalized Eigenvalue Problem ';
            app.NastranToolboxTab = uitab(app.TabGroup_2);
            app.NastranToolboxTab.Title = 'Nastran Toolbox';
            app.SelectAnalysisListBoxLabel_3 = uilabel(app.NastranToolboxTab);
            app.SelectAnalysisListBoxLabel_3.Text = 'Select Analysis';
            app.SelectAnalysisNastranListBox = uilistbox(app.NastranToolboxTab);
            app.SelectAnalysisNastranListBox.Items = {'Normal Modes Eigenvalues & Eigenvectors', 'Normal Modes Response Spectrum Analysis', 'Modal Transient Time Histories', 'Frequency Response Function for Base Excitation', 'Power Spectral Density Response for Base Excitation'};
            app.SelectAnalysisNastranListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonNastranPushed, true);
            app.SelectAnalysisNastranListBox.Value = 'Normal Modes Eigenvalues & Eigenvectors';
            app.AnalyzeButtonNastran = uibutton(app.NastranToolboxTab, 'push');
            app.AnalyzeButtonNastran.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonNastranPushed, true);
            app.AnalyzeButtonNastran.Text = 'Analyze';
            app.TransferFunctionsTab = uitab(app.TabGroup_2);
            app.TransferFunctionsTab.Title = 'Transfer Functions';
            app.AnalyzeButtonTrans = uibutton(app.TransferFunctionsTab, 'push');
            app.AnalyzeButtonTrans.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonTransPushed, true);
            app.AnalyzeButtonTrans.Text = 'Analyze';
            app.SelectAnalysisListBoxLabel_4 = uilabel(app.TransferFunctionsTab);
            app.SelectAnalysisListBoxLabel_4.Text = 'Select Analysis';
            app.SelectAnalysisTransListBox = uilistbox(app.TransferFunctionsTab);
            app.SelectAnalysisTransListBox.Items = {'Transfer Functions from Modes', 'Response from Input Time History and Transfer Function', 'State Space Method'};
            app.SelectAnalysisTransListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonTransPushed, true);
            app.SelectAnalysisTransListBox.Value = 'State Space Method';
            app.MiscellaneousTab = uitab(app.TabGroup_2);
            app.MiscellaneousTab.Title = 'Miscellaneous';
            app.SelectAnalysisListBoxLabel_5 = uilabel(app.MiscellaneousTab);
            app.SelectAnalysisListBoxLabel_5.Text = 'Select Analysis';
            app.SelectAnalysisMiscListBox = uilistbox(app.MiscellaneousTab);
            app.SelectAnalysisMiscListBox.Items = {'Damping Coefficient Matrix from Modal Damping', 'Stress-Velocity Relationship', 'Dunkerley Method', 'Area Moment of Inertia  ', ''};
            app.SelectAnalysisMiscListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonMiscPushed, true);
            app.SelectAnalysisMiscListBox.Value = 'Damping Coefficient Matrix from Modal Damping';
            app.AnalyzeButtonMisc = uibutton(app.MiscellaneousTab, 'push');
            app.AnalyzeButtonMisc.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonMiscPushed, true);
            app.AnalyzeButtonMisc.Text = 'Analyze';
            app.AcousticsTab = uitab(app.TabGroup);
            app.AcousticsTab.Title = 'Acoustics';
            app.AcousticsTab.ButtonDownFcn = createCallbackFcn(app, @AcousticsTabButtonDown, true);
            app.MainListBoxLabel = uilabel(app.AcousticsTab);
            app.MainListBoxLabel.Text = 'Main';
            app.MainListBox = uilistbox(app.AcousticsTab);
            app.MainListBox.Items = {'Acoustics', 'Vibroacoustics, Barrett & Franken Methods, etc.', 'Structural Wave Speeds', 'Mobility & Impedance', 'Mass Ratio Methods for Structures with Equipment', 'Structural-Borne Wave Propagation', 'SEA Models', 'Radiation Efficiency', 'Loss Factors', 'Modal Density & Overlap', 'Energy & Power', 'Toolboxes:  Beams, Panels, Cylinders', 'Critical Frequency, Ring Frequency & Wavelength', 'Launch Vehicle Acoustics', 'Sandwich Panel Toolbox', 'Statistical Response Concentration'};
            app.MainListBox.ValueChangedFcn = createCallbackFcn(app, @MainListBoxValueChanged, true);
            app.MainListBox.Value = 'Acoustics';
            app.SelectAcousticAnalysisListBoxLabel = uilabel(app.AcousticsTab);
            app.SelectAcousticAnalysisListBoxLabel.Text = 'Select Acoustic Analysis';
            app.SelectAcousticAnalysisListBox = uilistbox(app.AcousticsTab);
            app.SelectAcousticAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeAcousticsButtonPushed, true);
            app.AnalyzeAcousticsButton = uibutton(app.AcousticsTab, 'push');
            app.AnalyzeAcousticsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeAcousticsButtonPushed, true);
            app.AnalyzeAcousticsButton.Text = 'Analyze Acoustics';
            app.ToolboxesMiscTab = uitab(app.TabGroup);
            app.ToolboxesMiscTab.Title = 'Toolboxes & Misc';
            app.ToolboxesListBoxLabel = uilabel(app.ToolboxesMiscTab);
            app.ToolboxesListBoxLabel.Text = 'Toolboxes';
            app.ToolboxesListBox = uilistbox(app.ToolboxesMiscTab);
            app.ToolboxesListBox.ValueChangedFcn = createCallbackFcn(app, @ToolboxAnalyzeButtonPushed, true);
            app.MiscellaneousIListBoxLabel = uilabel(app.ToolboxesMiscTab);
            app.MiscellaneousIListBoxLabel.Text = 'Miscellaneous I';
            app.MiscellaneousIListBox = uilistbox(app.ToolboxesMiscTab);
            app.MiscellaneousIListBox.ValueChangedFcn = createCallbackFcn(app, @MiscIAnalyzeButtonPushed, true);
            app.MiscellaneousIIListBoxLabel = uilabel(app.ToolboxesMiscTab);
            app.MiscellaneousIIListBoxLabel.Text = 'Miscellaneous II';
            app.MiscellaneousIIListBox = uilistbox(app.ToolboxesMiscTab);
            app.MiscellaneousIIListBox.ValueChangedFcn = createCallbackFcn(app, @MiscIIAnalyzeButtonPushed, true);
            app.ToolboxAnalyzeButton = uibutton(app.ToolboxesMiscTab, 'push');
            app.ToolboxAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ToolboxAnalyzeButtonPushed, true);
            app.ToolboxAnalyzeButton.Text = 'Toolbox Analyze';
            app.MiscIAnalyzeButton = uibutton(app.ToolboxesMiscTab, 'push');
            app.MiscIAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @MiscIAnalyzeButtonPushed, true);
            app.MiscIAnalyzeButton.Text = 'Misc I Analyze';
            app.MiscIIAnalyzeButton = uibutton(app.ToolboxesMiscTab, 'push');
            app.MiscIIAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @MiscIIAnalyzeButtonPushed, true);
            app.MiscIIAnalyzeButton.Text = 'Misc II Analyze';
            app.FatigueFDSTab = uitab(app.TabGroup);
            app.FatigueFDSTab.Title = 'Fatigue FDS';
            app.SelectFatigueAnalysisListBoxLabel = uilabel(app.FatigueFDSTab);
            app.SelectFatigueAnalysisListBoxLabel.Text = 'Select Fatigue Analysis';
            app.SelectFatigueAnalysisListBox = uilistbox(app.FatigueFDSTab);
            app.SelectFatigueAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFatigueAnalysisListBoxValueChanged, true);
            app.FatigueAuxListBoxLabel = uilabel(app.FatigueFDSTab);
            app.FatigueAuxListBoxLabel.Text = 'Fatigue Aux';
            app.FatigueAuxListBox = uilistbox(app.FatigueFDSTab);
            app.FatigueAuxListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeFatigueButtonPushed, true);
            app.AnalyzeFatigueButton = uibutton(app.FatigueFDSTab, 'push');
            app.AnalyzeFatigueButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeFatigueButtonPushed, true);
            app.AnalyzeFatigueButton.Text = 'Analyze Fatigue';
            app.ShockTab = uitab(app.TabGroup);
            app.ShockTab.Title = 'Shock';
            app.SelectAnalysisIListBoxLabel = uilabel(app.ShockTab);
            app.SelectAnalysisIListBoxLabel.Text = 'Select Analysis I';
            app.SelectAnalysisIListBox = uilistbox(app.ShockTab);
            app.SelectAnalysisIListBox.Items = {'SRS Amplitude Conversion', 'SDOF Response: Classical Base Input', 'SDOF Response to Arbitrary Base Input or Applied Force', 'Shock Response Spectrum', 'Compare Two Half-Sine Pulse SRS', 'Half-Sine Shock Test, Drop Tower', 'Half-Sine or Terminal Sawtooth Shock Test, Shaker Table', 'Accidental Drop Shock', 'Shock Effective Duration MIL-STD-810F', 'Temporal Moments', 'Pyrotechnic Shock Propagation, Joint & Distance Attenuation', 'Stress Velocity Relationship', 'Spacecraft Clampband Release Shock SRS', 'Convert SRS Spec to a New Q Value', 'Derive SRS Spec from Time History', 'Convert Accel SRS to Pseudo Velocity SRS', 'SDOF Response:  Classical Applied Force', 'Satisfy SRS with Classical Pulse'};
            app.SelectAnalysisIListBox.Value = 'SRS Amplitude Conversion';
            app.SelectAnalysisIIListBoxLabel = uilabel(app.ShockTab);
            app.SelectAnalysisIIListBoxLabel.Text = 'Select Analysis II';
            app.SelectAnalysisIIListBox = uilistbox(app.ShockTab);
            app.SelectAnalysisIIListBox.Items = {'MDOF Shock, Single SRS Curve, Accel  ', 'MDOF Shock, Multiple SRS Curves, Accel', 'MDOF Shock, Multiple SRS Curves, Stress', ''};
            app.SelectAnalysisIIListBox.Value = 'MDOF Shock, Single SRS Curve, Accel  ';
            app.BeginAnalysisIIButton = uibutton(app.ShockTab, 'push');
            app.BeginAnalysisIIButton.ButtonPushedFcn = createCallbackFcn(app, @BeginAnalysisIIButtonPushed, true);
            app.BeginAnalysisIIButton.Text = 'Begin Analysis II';
            app.BeginAnalysisIButton = uibutton(app.ShockTab, 'push');
            app.BeginAnalysisIButton.ButtonPushedFcn = createCallbackFcn(app, @BeginAnalysisIButtonPushed, true);
            app.BeginAnalysisIButton.Text = 'Begin Analysis I';
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.TabGroup2 = uitabgroup(app.ImportDataTab);
            app.LibraryTab = uitab(app.TabGroup2);
            app.LibraryTab.Title = 'Library';
            app.ReadDataButton = uibutton(app.LibraryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed2, true);
            app.ReadDataButton.Text = 'Read Data';
            app.SelectArrayListBoxLabel = uilabel(app.LibraryTab);
            app.SelectArrayListBoxLabel.Text = 'Select Array';
            app.SelectArrayListBox = uilistbox(app.LibraryTab);
            app.SelectArrayListBox.ValueChangedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.SelectTypeListBoxLabel = uilabel(app.LibraryTab);
            app.SelectTypeListBoxLabel.Text = 'Select Type';
            app.SelectTypeListBox = uilistbox(app.LibraryTab);
            app.SelectTypeListBox.Items = {'Acceleration Time History', 'Acoustic Time History', 'Pyrotechnic Shock Time History    ', 'Earthquake Time History', 'Stress Time History', 'PSD', 'Sound Pressure Level (SPL)', 'SRS', 'Wavelet Table', 'Sound Files', 'Sound Power Level'};
            app.SelectTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTypeListBoxValueChanged, true);
            app.SelectTypeListBox.Value = 'Acceleration Time History';
            app.TextorExcelTab = uitab(app.TabGroup2);
            app.TextorExcelTab.Title = 'Text or Excel';
            app.ReadFileMethod1Button = uibutton(app.TextorExcelTab, 'push');
            app.ReadFileMethod1Button.ButtonPushedFcn = createCallbackFcn(app, @ReadFileMethod1ButtonPushed, true);
            app.ReadFileMethod1Button.Text = 'Read File Method 1';
            app.Label_2 = uilabel(app.TextorExcelTab);
            app.Label_2.Text = {'A = readmatrix(filename) creates an array by reading column-oriented '; 'data from a file. The readmatrix function performs automatic detection '; 'of import parameters for your file.'; ''; 'readmatrix determines the file format from the file extension:'; ''; '.txt, .dat, or .csv for delimited text files'; ''; '.xls, .xlsb, .xlsm, .xlsx, .xltm, .xltx, or .ods for spreadsheet files'; ''; 'For files containing mixed numeric and text data, readmatrix imports '; 'the data as a numeric array by default'};
            app.ReadFileMethod2Button = uibutton(app.TextorExcelTab, 'push');
            app.ReadFileMethod2Button.ButtonPushedFcn = createCallbackFcn(app, @ReadFileMethod2ButtonPushed, true);
            app.ReadFileMethod2Button.Text = 'Read File Method 2';
            app.importdatamethodLabel = uilabel(app.TextorExcelTab);
            app.importdatamethodLabel.Text = 'importdata method ';
            app.matTab = uitab(app.TabGroup2);
            app.matTab.Title = 'mat';
            app.ReadmatButton = uibutton(app.matTab, 'push');
            app.ReadmatButton.ButtonPushedFcn = createCallbackFcn(app, @ReadmatButtonPushed, true);
            app.ReadmatButton.Text = 'Read .mat';
            app.ManualDataEntryTab = uitab(app.TabGroup2);
            app.ManualDataEntryTab.Title = 'Manual Data Entry';
            app.UITableManual = uitable(app.ManualDataEntryTab);
            app.UITableManual.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableManual.RowName = {};
            app.UITableManual.ColumnEditable = [true true true true];
            app.NumberofColumnsListBoxLabel = uilabel(app.ManualDataEntryTab);
            app.NumberofColumnsListBoxLabel.Text = 'Number of Columns';
            app.NumberofColumnsListBox = uilistbox(app.ManualDataEntryTab);
            app.NumberofColumnsListBox.Items = {'1', '2', '3', '4'};
            app.NumberofColumnsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofColumnsListBoxValueChanged, true);
            app.NumberofColumnsListBox.Value = '1';
            app.NumberofRowsListBoxLabel = uilabel(app.ManualDataEntryTab);
            app.NumberofRowsListBoxLabel.Text = 'Number of  Rows';
            app.NumberofRowsListBox = uilistbox(app.ManualDataEntryTab);
            app.NumberofRowsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofRowsListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofRowsListBoxValueChanged, true);
            app.NumberofRowsListBox.Value = '1';
            app.SaveButton_2 = uibutton(app.ManualDataEntryTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.OutputArrayNameEditField_5Label_2 = uilabel(app.ManualDataEntryTab);
            app.OutputArrayNameEditField_5Label_2.Text = 'Output Array Name';
            app.OutputArrayNameEditField_Manual = uieditfield(app.ManualDataEntryTab, 'text');
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotTab.ButtonDownFcn = createCallbackFcn(app, @PlotTabButtonDown, true);
            app.PlotIButton = uibutton(app.PlotTab, 'push');
            app.PlotIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIButtonPushed2, true);
            app.PlotIButton.Text = 'Plot I';
            app.PlotIIButton = uibutton(app.PlotTab, 'push');
            app.PlotIIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIIButtonPushed2, true);
            app.PlotIIButton.Text = 'Plot II';
            app.SelectPlotTypeIIListBoxLabel = uilabel(app.PlotTab);
            app.SelectPlotTypeIIListBoxLabel.Text = 'Select Plot Type II';
            app.SelectPlotTypeIIListBox = uilistbox(app.PlotTab);
            app.SelectPlotTypeIIListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPlotTypeIIListBoxValueChanged, true);
            app.SelectPlotTypeIListBoxLabel = uilabel(app.PlotTab);
            app.SelectPlotTypeIListBoxLabel.Text = 'Select Plot Type I';
            app.SelectPlotTypeIListBox = uilistbox(app.PlotTab);
            app.SelectPlotTypeIListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPlotTypeIListBoxValueChanged, true);
            app.LVTab = uitab(app.TabGroup);
            app.LVTab.Title = 'LV';
            app.SelectLVAnalysisListBoxLabel = uilabel(app.LVTab);
            app.SelectLVAnalysisListBoxLabel.Text = 'Select LV Analysis';
            app.SelectLVAnalysisListBox = uilistbox(app.LVTab);
            app.SelectLVAnalysisListBox.Items = {'Vibration', 'Shock'};
            app.SelectLVAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectLVAnalysisListBoxValueChanged, true);
            app.SelectLVAnalysisListBox.Value = 'Shock';
            app.LVAuxListBoxLabel = uilabel(app.LVTab);
            app.LVAuxListBoxLabel.Text = 'LV Aux';
            app.LVAuxListBox = uilistbox(app.LVTab);
            app.LVAuxListBox.ValueChangedFcn = createCallbackFcn(app, @NSAnalyzeButtonPushed, true);
            app.LVAnalyzeButton = uibutton(app.LVTab, 'push');
            app.LVAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @NSAnalyzeButtonPushed, true);
            app.LVAnalyzeButton.Text = 'LV Analyze';
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            app.WorkspaceTab.ButtonDownFcn = createCallbackFcn(app, @WorkspaceTabButtonDown, true);
            app.UpdateButton = uibutton(app.WorkspaceTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ClearButton = uibutton(app.WorkspaceTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.WorkspaceListBoxLabel = uilabel(app.WorkspaceTab);
            app.WorkspaceListBoxLabel.Text = 'Workspace';
            app.WorkspaceListBox = uilistbox(app.WorkspaceTab);
            app.WorkspaceListBox.Items = {''};
            app.WorkspaceListBox.Value = '';
            app.CloseAllPlotsButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.CloseAllPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllPlotsButtonPushed, true);
            app.CloseAllPlotsButton.Text = 'Close All Plots';
            app.ClearApplicationDataButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.ClearApplicationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearApplicationDataButtonPushed, true);
            app.ClearApplicationDataButton.Text = 'Clear Application Data';
            app.ExportDataButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Label = uilabel(app.vibrationdata_AppUIFigure);
            app.Label.Text = 'vibrationdata_App    ver 6.7  by Tom Irvine    Multi-function Signal Analysis Script & More  ';
            app.CalculateFFTButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.SDOFResponsetoBaseInputAppliedForceButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.SDOFResponsetoBaseInputAppliedForceButton.ButtonPushedFcn = createCallbackFcn(app, @SDOFResponsetoBaseInputAppliedForceButtonPushed, true);
            app.SDOFResponsetoBaseInputAppliedForceButton.Text = {'SDOF Response '; 'to Base Input '; '& Applied Force'};
            app.CheckforVersionUpdateButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.CheckforVersionUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforVersionUpdateButtonPushed, true);
            app.CheckforVersionUpdateButton.Text = 'Check for Version Update';
            app.ReturnButton = uibutton(app.vibrationdata_AppUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.vibrationdata_AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.vibrationdata_AppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.vibrationdata_AppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vibrationdata_AppUIFigure)
        end
    end
end