classdef vibrationdata_alt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vibrationdataaltUIFigure        matlab.ui.Figure
        SDOFResponsetoBaseInputButton   matlab.ui.control.Button
        CalculateFFTButton              matlab.ui.control.Button
        CheckforVersionUpdateButton     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SignalProcessingTab             matlab.ui.container.Tab
        SelectFunctionListBox           matlab.ui.control.ListBox
        SelectFunctionListBoxLabel      matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        SelectListBox_signal            matlab.ui.control.ListBox
        SelectAnalysisLabel             matlab.ui.control.Label
        StructuralDynamicsTab           matlab.ui.container.Tab
        SelectSystemListBox             matlab.ui.control.ListBox
        SelectSystemListBoxLabel        matlab.ui.control.Label
        AnalyzeButton_StructuralDynamics  matlab.ui.control.Button
        SelectAnalysisListBox           matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        GenerateTab                     matlab.ui.container.Tab
        GenerateTabGroup                matlab.ui.container.TabGroup
        SignalIndexTab                  matlab.ui.container.Tab
        PerformAnalysisButton           matlab.ui.control.Button
        GenerateListBox                 matlab.ui.control.ListBox
        SelectSignalLabel               matlab.ui.control.Label
        aTab                            matlab.ui.container.Tab
        OutputNameEditField             matlab.ui.control.EditField
        OutputNameEditFieldLabel        matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        YaxisLabelEditField             matlab.ui.control.EditField
        YaxisLabelEditFieldLabel        matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        TabGroup_2                      matlab.ui.container.TabGroup
        SineTab                         matlab.ui.container.Tab
        CalculateButton_sine            matlab.ui.control.Button
        RectifyListBox                  matlab.ui.control.ListBox
        RectifyListBoxLabel             matlab.ui.control.Label
        PhaseAngledegEditField          matlab.ui.control.EditField
        PhaseAngledegEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        MultipleSineTonesTab            matlab.ui.container.Tab
        CalculateButton_multi           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        NumberofTonesListBox            matlab.ui.control.ListBox
        NumberofTonesListBoxLabel       matlab.ui.control.Label
        CosineTab                       matlab.ui.container.Tab
        FrequencyHzEditField_2          matlab.ui.control.EditField
        FrequencyHzEditField_2Label     matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.EditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        CalculateButton_cosine          matlab.ui.control.Button
        DampedSineTab                   matlab.ui.container.Tab
        AmplitudeEditField_dsine        matlab.ui.control.EditField
        DelayTimeEditFieldLabel_2       matlab.ui.control.Label
        DelayTimeEditField              matlab.ui.control.EditField
        DelayTimeEditFieldLabel         matlab.ui.control.Label
        CalculateButton_damped_sine     matlab.ui.control.Button
        DampingRatio1EditField          matlab.ui.control.EditField
        DampingRatio1EditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField_dsine      matlab.ui.control.EditField
        FrequencyHzEditField_3Label     matlab.ui.control.Label
        bTab                            matlab.ui.container.Tab
        TabGroup_3                      matlab.ui.container.TabGroup
        SineSweepTab                    matlab.ui.container.Tab
        NumberofOctavesEditField        matlab.ui.control.NumericEditField
        NumberofOctavesEditFieldLabel   matlab.ui.control.Label
        CalculateSpectrumFunctionListBox  matlab.ui.control.ListBox
        CalculateSpectrumFunctionListBoxLabel  matlab.ui.control.Label
        UITable_sweep                   matlab.ui.control.Table
        NumberofCoordinatesListBox      matlab.ui.control.ListBox
        NumberofCoordinatesListBoxLabel  matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        TimeOptionListBox               matlab.ui.control.ListBox
        TimeOptionListBoxLabel          matlab.ui.control.Label
        DurationsecEditField_sweep      matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel_sweep  matlab.ui.control.Label
        SampleRateHzEditField_sweep     matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel_sweep  matlab.ui.control.Label
        SweepDirectionListBox           matlab.ui.control.ListBox
        SweepDirectionListBoxLabel      matlab.ui.control.Label
        SweepTypeListBox                matlab.ui.control.ListBox
        SweepTypeListBoxLabel           matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        SDOFResponseTab                 matlab.ui.container.Tab
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CalculateListBox                matlab.ui.control.ListBox
        CalculateListBoxLabel           matlab.ui.control.Label
        RelDispResponseArrayEditField   matlab.ui.control.EditField
        RelDispResponseArrayEditFieldLabel  matlab.ui.control.Label
        AccelResponseArrayEditField     matlab.ui.control.EditField
        AccelResponseArrayEditFieldLabel  matlab.ui.control.Label
        BaseExcitationArrayEditField    matlab.ui.control.EditField
        BaseExcitationArrayEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ResultsTextArea_sweep           matlab.ui.control.TextArea
        ResultsTextAreaLabel_sweep      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        cTab                            matlab.ui.container.Tab
        YaxisLabelEditField_white       matlab.ui.control.EditField
        YaxisLabelEditField_2Label      matlab.ui.control.Label
        HighpassFreqHzEditField         matlab.ui.control.EditField
        HighpassFreqHzEditFieldLabel    matlab.ui.control.Label
        LowpassFreqHzEditField          matlab.ui.control.EditField
        LowpassFreqHzEditFieldLabel     matlab.ui.control.Label
        BandlimitviaFilteringListBox    matlab.ui.control.ListBox
        BandlimitviaFilteringListBoxLabel  matlab.ui.control.Label
        ResultsTextArea_white           matlab.ui.control.TextArea
        ResultsTextAreaLabel_white      matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        WhiteBasicTab                   matlab.ui.container.Tab
        OutputNameEditField_white       matlab.ui.control.EditField
        OutputNameEditField_2Label      matlab.ui.control.Label
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        YaxisLabelEditField_white_2     matlab.ui.control.EditField
        YaxisLabelEditField_2Label_2    matlab.ui.control.Label
        SampleRateHzEditField_white     matlab.ui.control.EditField
        SampleRateHzEditField_2Label    matlab.ui.control.Label
        DurationsecEditField_white      matlab.ui.control.EditField
        DurationsecEditField_2Label     matlab.ui.control.Label
        StdDevEditField                 matlab.ui.control.EditField
        StdDevEditFieldLabel            matlab.ui.control.Label
        CalculateButton_white_noise_basic  matlab.ui.control.Button
        WhiteCorrectedTab               matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        T3_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2  matlab.ui.control.Label
        T2_ListBox_cor                  matlab.ui.control.ListBox
        SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor  matlab.ui.control.Label
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        StdDevEditField_wc              matlab.ui.control.EditField
        StdDevEditField_2Label          matlab.ui.control.Label
        DurationsecEditField_wc         matlab.ui.control.EditField
        DurationsecEditField_5Label     matlab.ui.control.Label
        SampleRateHzEditField_wc        matlab.ui.control.EditField
        SampleRateHzEditField_4Label    matlab.ui.control.Label
        DurationTaperPercentEditField_cor  matlab.ui.control.EditField
        DurationTaperPercentEditFieldLabel_cor  matlab.ui.control.Label
        CalculateButton_white_noise_corrected  matlab.ui.control.Button
        SaveTab                         matlab.ui.container.Tab
        OutputNameEditField_cor         matlab.ui.control.EditField
        OutputNameEditFieldLabel_cor    matlab.ui.control.Label
        ExportFunctionListBox_cor       matlab.ui.control.ListBox
        ExportFunctionListBoxLabel_cor  matlab.ui.control.Label
        SaveButton_cor                  matlab.ui.control.Button
        TwoCorrelatedWhiteSignalsTab    matlab.ui.container.Tab
        StdDev2EditField                matlab.ui.control.EditField
        StdDev2EditFieldLabel           matlab.ui.control.Label
        StdDev1EditField                matlab.ui.control.EditField
        StdDev1EditFieldLabel           matlab.ui.control.Label
        SampleRateHzEditField_two_white  matlab.ui.control.EditField
        SampleRateHzEditField_2Label_2  matlab.ui.control.Label
        DurationsecEditField_two_white  matlab.ui.control.EditField
        DurationsecEditField_5Label_2   matlab.ui.control.Label
        CorrelationCoefficientEditField  matlab.ui.control.EditField
        CorrelationCoefficientEditFieldLabel  matlab.ui.control.Label
        OutputNameEditField_two_white   matlab.ui.control.EditField
        OutputNameEditField_2Label_2    matlab.ui.control.Label
        CalculateButton_two_correlated_white  matlab.ui.control.Button
        AddWhitetoSignalTab             matlab.ui.container.Tab
        SelectInputArrayListBox_add_white  matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel_2  matlab.ui.control.Label
        UpdateListButton_2              matlab.ui.control.Button
        listbox_method                  matlab.ui.control.ListBox
        text1                           matlab.ui.control.Label
        StdDevEditField_add_white       matlab.ui.control.EditField
        StdDevEditField_2Label_2        matlab.ui.control.Label
        OutputNameEditField_add_white   matlab.ui.control.EditField
        OutputNameEditField_2Label_3    matlab.ui.control.Label
        CalculateButton_add_white_noise  matlab.ui.control.Button
        dTab                            matlab.ui.container.Tab
        SampleRateEditField_sk          matlab.ui.control.EditField
        DurationsecEditField_2Label_4   matlab.ui.control.Label
        YaxisLabelEditField_sk          matlab.ui.control.EditField
        YaxisLabelEditField_2Label_3    matlab.ui.control.Label
        StdDevEditField_sk              matlab.ui.control.EditField
        DurationsecEditField_2Label_3   matlab.ui.control.Label
        DurationsecEditField_sk         matlab.ui.control.EditField
        DurationsecEditField_2Label_2   matlab.ui.control.Label
        ResultsTextArea_random_sk       matlab.ui.control.TextArea
        ResultsTextAreaLabel_random_sk  matlab.ui.control.Label
        TabGroup7                       matlab.ui.container.TabGroup
        RandomwithSkewnessKurtosisTab   matlab.ui.container.Tab
        SkewnessListBox                 matlab.ui.control.ListBox
        SkewnessListBoxLabel            matlab.ui.control.Label
        RandomNumberSeedListBox_2       matlab.ui.control.ListBox
        RandomNumberSeedListBox_2Label  matlab.ui.control.Label
        KurtosisEditField               matlab.ui.control.EditField
        KurtosisEditFieldLabel          matlab.ui.control.Label
        BandlimitviaLowpassFilterListBox_Sarkani_sk  matlab.ui.control.ListBox
        BandlimitviaLowpassFilterListBoxLabel  matlab.ui.control.Label
        Filteratsamplerate10Label       matlab.ui.control.Label
        OutputNameEditField_sk          matlab.ui.control.EditField
        OutputNameEditField_2Label_4    matlab.ui.control.Label
        CalculateButton_sk              matlab.ui.control.Button
        SarkaniRandomTab                matlab.ui.container.Tab
        BandlimitviaLowpassFilterListBox_Sarkani  matlab.ui.control.ListBox
        BandlimitviaLowpassFilterListBoxLabel_2  matlab.ui.control.Label
        kurtosisEditField               matlab.ui.control.EditField
        kurtosisEditFieldLabel          matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        nEditFieldLabel                 matlab.ui.control.Label
        betaEditField                   matlab.ui.control.EditField
        betaEditFieldLabel              matlab.ui.control.Label
        SpecificationMethodListBox      matlab.ui.control.ListBox
        SpecificationMethodListBoxLabel  matlab.ui.control.Label
        Filteratsamplerate10Label_2     matlab.ui.control.Label
        OutputNameEditField_Sarkani     matlab.ui.control.EditField
        OutputNameEditField_2Label_5    matlab.ui.control.Label
        CalculateButton_Sarkani         matlab.ui.control.Button
        PinkNoiseTab                    matlab.ui.container.Tab
        PinknoiseovertheaudiofrequencydomainLabel  matlab.ui.control.Label
        OutputNameEditField_pink        matlab.ui.control.EditField
        OutputNameEditField_2Label_6    matlab.ui.control.Label
        CalculateButton_pink            matlab.ui.control.Button
        FatigueTab                      matlab.ui.container.Tab
        AnalyzeButton_3                 matlab.ui.control.Button
        FatigueListBox                  matlab.ui.control.ListBox
        FatigueListBoxLabel             matlab.ui.control.Label
        AcousticsTab                    matlab.ui.container.Tab
        AnalyzeButton_4                 matlab.ui.control.Button
        AnalysisListBox                 matlab.ui.control.ListBox
        AnalysisListBoxLabel            matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        SelectPlotTypeListBox           matlab.ui.control.ListBox
        SelectPlotTypeListBoxLabel      matlab.ui.control.Label
        UtilitiesTab                    matlab.ui.container.Tab
        AnalyzeButton_Utilities         matlab.ui.control.Button
        SelectAnalysisListBox_Utilities  matlab.ui.control.ListBox
        SelectAnalysisListBox_2Label    matlab.ui.control.Label
        ImportTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        LibraryDataTab                  matlab.ui.container.Tab
        ReadDataButton                  matlab.ui.control.Button
        SelectArrayListBox              matlab.ui.control.ListBox
        SelectArrayListBoxLabel         matlab.ui.control.Label
        SelectTypeListBox               matlab.ui.control.ListBox
        SelectTypeListBoxLabel          matlab.ui.control.Label
        TextorExcelTab                  matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ReadFileButton                  matlab.ui.control.Button
        matTab                          matlab.ui.container.Tab
        ReadmatButton                   matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        uipanel2                        matlab.ui.container.Panel
        listbox_format                  matlab.ui.control.ListBox
        uipanel1                        matlab.ui.container.Panel
        PerformExportButton             matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        pushbutton_return               matlab.ui.control.Button
        WorkspaceTab                    matlab.ui.container.Tab
        WorkspaceListBox                matlab.ui.control.ListBox
        WorkspaceListBoxLabel           matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        vibrationdata_altver44byTomIrvineemailtomirvinemailorgLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM                   % Description
        mfunction             %
        n_structural_dynamics %
        nf                    %
        ntype                 %
        num                   %
        fff                   %
        signal                % 
        accel                 %
        vel                   %
        dispx                 %
    end
    
    
    methods (Access = private)

        function update_add_white(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox_add_white.Items=unique(sort(cell_array)); 
        end           

        function Sarkani(app)

            Items = char(app.SpecificationMethodListBox.Items);
            im=app.SpecificationMethodListBox.Value;
            [nmeth]=find_index(Items,im);

            app.nEditField.Visible='off';
            app.nEditFieldLabel.Visible='off';
            app.betaEditField.Visible='off';
            app.betaEditFieldLabel.Visible='off';
            app.kurtosisEditField.Visible='off';
            app.kurtosisEditFieldLabel.Visible='off';

            if(nmeth==1)
                app.kurtosisEditField.Visible='on';
                app.kurtosisEditFieldLabel.Visible='on';    
            else
                app.nEditField.Visible='on';
                app.nEditFieldLabel.Visible='on';
                app.betaEditField.Visible='on';
                app.betaEditFieldLabel.Visible='on';
            end
        end            

        function ChangeFilter(app)
            Items = char(app.BandlimitviaFilteringListBox.Items);
            im=app.BandlimitviaFilteringListBox.Value;
            [app.nf]=find_index(Items,im);          

            app.HighpassFreqHzEditField.Visible='off';
            app.HighpassFreqHzEditFieldLabel.Visible='off';

            if(app.nf==1 || app.nf==2)
                app.LowpassFreqHzEditField.Visible='on';
                app.LowpassFreqHzEditFieldLabel.Visible='on';
            end     

            if(app.nf==1)                
                app.LowpassFreqHzEditFieldLabel.Text='Low-pass Freq (Hz)';
            end
            if(app.nf==2)
                app.LowpassFreqHzEditFieldLabel.Text='Low-pass Freq (Hz)';
                app.HighpassFreqHzEditField.Visible='on';
                app.HighpassFreqHzEditFieldLabel.Visible='on';            
            end
            if(app.nf==3)
                app.LowpassFreqHzEditField.Visible='off';
                app.LowpassFreqHzEditFieldLabel.Visible='off';
            end    
        end               

        function ChangeTimeOption(app)
            n=app.SweepTypeListBox.Value;
            app.DurationsecEditFieldLabel_sweep.Text='Duration (sec)';
                
            if(strcmp(n,'linear'))
                app.TimeOptionListBox.Visible='off';
                app.TimeOptionListBoxLabel.Visible='off';
                app.ntype=1;
            else
                app.TimeOptionListBox.Visible='on';
                app.TimeOptionListBoxLabel.Visible='on';
                m=app.TimeOptionListBox.Value;
                if(contains(m,'Sweep'))
                    app.DurationsecEditFieldLabel_sweep.Text='Sweep Rate (oct/min)';    
                end
                app.ntype=2;
            end
        end               

        function ChangeNumberCoordinates(app)
            ns=app.NumberofCoordinatesListBox.Value;
            app.num=str2num(ns); 
            
            q=zeros(app.num,2);
            
            app.UITable_sweep.Data=q;
            
        end        

        function LB_change(app)
            clear(app); 

            N=str2num(app.NumberofTonesListBox.Value);
           
            for i = 1:N
               for j=1:2
                  data_s{i,j} = '';     
               end  
               data_s{i,3} = '0';   
            end
 
            app.UITable.Data=data_s;          
        end
        

        function clear(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end  

%            app.ResultsTextArea_add_sine.Visible='off';
%            app.ResultsTextAreaLabel_add_sine.Visible='off';          
%            app.ResultsTextArea_student.Visible='off';
%            app.ResultsTextAreaLabel_student.Visible='off';
            app.ResultsTextAreaLabel_random_sk.Visible='off';
            app.ResultsTextArea_random_sk.Visible='off';           
            % app.SDOFResponsetoBaseInputButton.Visible='off';
            app.OutputNameEditFieldLabel.Visible='off';
            app.OutputNameEditField.Visible='off';
            app.SaveButton.Visible='off';  
            % app.CalculateFFTButton.Visible='off';     
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.ResultsTextArea_sweep.Visible='off';
            app.ResultsTextAreaLabel_sweep.Visible='off';   
            app.ResultsTextArea_white.Visible='off';
            app.ResultsTextAreaLabel_white.Visible='off';     
            app.NumberofOctavesEditField.Visible='off';
            app.NumberofOctavesEditFieldLabel.Visible='off';              
        end            

        function GenerateChange(app)

            Items = char(app.GenerateListBox.Items);
            im=app.GenerateListBox.Value;
            [n]=find_index(Items,im); 

            app.GenerateTabGroup.SelectedTab=app.GenerateTabGroup.Children(n);
        end            
        
        function update_list(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    cell_array{i} = vars(i).name;
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.WorkspaceListBox.Items=unique(sort(cell_array)); 
            app.SelectInputArrayListBox.Items=app.WorkspaceListBox.Items;
        end    

        
        function tab_change(app)
            if(contains(app.TabGroup.SelectedTab.Title,'Structural Dynamics'))
                SelectSystem(app);
            end   
        end

        function SelectSystem(app)
            sss_sdof={'Natural Frequency & Response to Initial Conditions'; 
                     'Base Input & Force Steady Sine, Peak Sigma, Miles Eq, Crest Factor Experiment';
                     'Classic Base Input';
                     'Arbitrary Base Input or Applied Force'};

            sss_twodof={'Base Excitation Response'};

            sss_beam={'Classical Method, Natural Frequency & Base Excitation';
                      'Finite Element Method & Base Excitation'};

            sss_plate={'Panel Subjected to Point Force, Impedance Method';
                       'Plate, Common BCs, Natural Frequencies & Response';'Plate Bending FEA'};

            Items = char(app.SelectSystemListBox.Items);
            im=app.SelectSystemListBox.Value;
            [app.n_structural_dynamics]=find_index(Items,im);

            if(app.n_structural_dynamics==1)
                app.SelectAnalysisListBox.Items=sss_sdof;
            end
            if(app.n_structural_dynamics==2)
                app.SelectAnalysisListBox.Items=sss_twodof;
            end
            if(app.n_structural_dynamics==3)
                app.SelectAnalysisListBox.Items=sss_beam;
            end

            if(app.n_structural_dynamics==4)
                app.SelectAnalysisListBox.Items=sss_plate;
            end

        end
        
        function signal_analysis(app)

            Items = char(app.SelectListBox_signal.Items);
            im=app.SelectListBox_signal.Value;
            [n]=find_index(Items,im);

            if(app.mfunction==1)    
                if(n==1)
                    signal_editing_utilities_a;
                end                
                if(n==2)
                    vibrationdata_fourier_transform_ah;
                end
                if(n==3)
                    waterfall_fft_ah;
                end
                if(n==4)
                    vibrationdata_psd_ah;
                end
                if(n==5)
                    power_transmissibility_ah;
                end          
                if(n==6)
                    sine_curve_fit_ah;
                end    
                if(n==7)
                    srs_base_ah;
                end 
                if(n==8)
                    vibrationdata_spl_a;
                end    
                if(n==9)
                    filter_App;
                end      
                if(n==10)
                    signal_correlation_a;
                end     
                if(n==11 || n==12)
                    integrate_differentiate_a;
                end  
                if(n==13)
                    iso_main;
                end    
            end
            if(app.mfunction==2)  
                if(n==1)
                    psd_plot_App;
                end      
                if(n==2)
                    vibrationdata_psd_synth_ah;
                end   
                if(n==3)
                    vrs_fds_base;
                end   
                if(n==4)
                    vrs_fds_force;
                end               
                if(n==5)
                    envelope_psd_a;
                end
                if(n==6)
                    stress_from_acceleration_psd;         
                end   
                if(n==7)
                    psd_tools_a;         
                end            
            end    
            if(app.mfunction==3)  
                if(n==1)
                    wavelet_synth_a;
                end      
            end                
        end
        
        function select_generate(app)
            Items = char(app.SelectSignalListBox.Items);
            im=app.SelectSignalListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                signal_sine_a;
            end
            if(n==2)
                signal_sine_sweep_a;
            end
            if(n==3)
                white_noise_a;
            end
            if(n==4)
                random_skewness_kurtosis_a;
            end   
        end
        
        function SelectSD(app)
            Items = char(app.SelectAnalysisListBox.Items);
            im=app.SelectAnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(app.n_structural_dynamics==1)  % sdof
                if(n==1)
                    sdof_fn_a;
                end    
                if(n==2)
                    sdof_response_various_a;
                end
                if(n==3)
                    classical_pulse_base_input_a;        
                end    
                if(n==4)
                    sdof_response_time_domain; 
                end              
            end
            if(app.n_structural_dynamics==2)  % two-dof
                if(n==1)
                    two_dof_system_ah;
                end       
            end 
            if(app.n_structural_dynamics==3)  % beam
                if(n==1)
                    beam_bending_a;
                end
                if(n==2)
                    beam_bending_FEA_a;
                end             
            end 
            if(app.n_structural_dynamics==4)  % plate
                if(n==1)
                    panel_point_force_multi_a;
                end
                if(n==2)
                    rectangular_plate_bending_a;
                end  
                if(n==3)
                    vibrationdata_rectangular_plate_fea_app;
                end               
            end             
        end
        
        function library_read(app)
            Items = char(app.SelectTypeListBox.Items);
            im=app.SelectTypeListBox.Value;
            [n]=find_index(Items,im);

            if(n==1) % Acceleration Time History

                string_th{1}=sprintf('SRS 2000G Acceleration 1');
                string_th{end+1}=sprintf('SRS 2000G Acceleration 2');               
                string_th{end+1}=sprintf('SRS 1000G Acceleration ');
                string_th{end+1}=sprintf('Re-entry Vehicle Separation, LSC, Raw');
                string_th{end+1}=sprintf('Re-entry Vehicle Separation, LSC, Cleaned');    
                string_th{end+1}=sprintf('Flight Accelerometer Data, Suborbital Launch Vehicle');
                string_th{end+1}=sprintf('Solid Rocket Motor, Resonant Burn, Flight Data');
                string_th{end+1}=sprintf('Channel Beam');
                string_th{end+1}=sprintf('Drop Transient, Launch Vehicle');
                string_th{end+1}=sprintf('Space Shuttle SRB FWD IEA Slashdown');
                string_th{end+1}=sprintf('Flight Stage Separation Shock');
                string_th{end+1}=sprintf('Taurus Auto');  
                string_th{end+1}=sprintf('Complex Oscillating Pulse');  
                string_th{end+1}=sprintf('NASA HDBK 7005 Mid-Field Pyrotechnic Shock');  
                string_th{end+1}=sprintf('Solid Rocket Motor Dome, Static Fire Test'); 
                string_th{end+1}=sprintf('Orion Pad Abort Test');    
                string_th{end+1}=sprintf('Bike over Gravel Road');     
            end
            if(n==2) % Acoustic Time History    
                
                string_th{1}=sprintf('Tuning Fork');
                string_th{2}=sprintf('Transformer Magnetostriction');
                string_th{3}=sprintf('Q400 Turboprop Aircraft');
                string_th{4}=sprintf('Apache Helicopter Flyover');  
                string_th{5}=sprintf('Hoover Dam Turbine Generators');  
                string_th{6}=sprintf('Launch Abort System Pad One Test');     
            end
            if(n==3) % Earthquake Time History
                
                string_th{1}=sprintf('El Centro earthquake NS');
                string_th{2}=sprintf('El Centro earthquake EW');
                string_th{3}=sprintf('El Centro earthquake UP');
                string_th{4}=sprintf('El Centro earthquake Triaxial');  
                string_th{5}=sprintf('Solomon Island earthquake');   
                string_th{6}=sprintf('GR-63-Core Zone 4 Synthesis');    
            end
            if(n==4) % Stress Time History
                string_th{1}=sprintf('ASTM E 1049 85 - Rainflow Stress');
            end
            if(n==5) % PSD
            
                string_th{1}=sprintf('NAVMAT PSD Specification');
                string_th{2}=sprintf('SMC-S-016 Minimum Workmanship PSD Specification');
                string_th{3}=sprintf('MIL-STD-1540B ATP PSD Specification');
                string_th{4}=sprintf('Aircraft External Fuselage Pressure PSD in Flight');
                string_th{5}=sprintf('HALT PSD Sample');
                string_th{6}=sprintf('Flight Data Sample');
                string_th{7}=sprintf('Test Spec Sample');   
                string_th{8}=sprintf('Suborbital Vehicle Bulkhead Flight Data'); 
                string_th{9}=sprintf('Saturn V Honeycomb Sandwich Test Data');  
                string_th{10}=sprintf('NASA/TM-2009-215902 Saturn V PSDs'); 
                string_th{11}=sprintf('NASA TN-D 1836 PSDs');  
            end
            if(n==6) % SPL
            
                string_th{1}=sprintf('MIL-STD-1540C SPL, Acceptance');
                string_th{2}=sprintf('Launch Vehicle Avionics Module Liftoff SPL');
                string_th{3}=sprintf('Saturn V Reference, One-Third Octave');
                string_th{4}=sprintf('P31'); 
                string_th{5}=sprintf('P32');  
                string_th{6}=sprintf('Sample Liftoff Ground SPL');    
                string_th{7}=sprintf('LV OML SPL, Descent Transonic');     
                string_th{8}=sprintf('Payload Fairing Internal SPL');   
            end
            if(n==7) % SRS
                string_th{1}=sprintf('MIL-STD-810G Crash Hazard');
                string_th{2}=sprintf('Vandenberg AFB 1%% Damping');
                string_th{3}=sprintf('Harris 2%% Damping');
                string_th{4}=sprintf('IEEE std 693 RSS, Various');
                string_th{5}=sprintf('GR-63 Core Zone 4');    
                string_th{6}=sprintf('GR-63 Core Zone 3');    
                string_th{7}=sprintf('GR-63 Core Zones 1 & 2');    
                string_th{8}=sprintf('ANSI C37.98 Normalized');     
                string_th{9}=sprintf('Frangible Joint Source Shock, 26 grains/ft');
                string_th{10}=sprintf('NASA LSC Source Shock, 30 grains/ft'); 
                string_th{11}=sprintf('Generic Minimum Level');      
                string_th{12}=sprintf('50 in/sec');     
                string_th{13}=sprintf('Ariane 5 Payload Interface');
                string_th{14}=sprintf('RUAG Payload Adapter System 3100SX');    
            end
            if(n==8) % Wavelet Table
                string_th{1}=sprintf('wavelet_2k');
                string_th{2}=sprintf('wavelet_11k');
            end
            if(n==9) % Sound Files
                string_th{1}=sprintf('Apache Helicopter Flyover');
                string_th{2}=sprintf('Sine Sweep');    
                string_th{3}=sprintf('Transformer Hum');
                string_th{4}=sprintf('Tuning Fork');  
            end
            if(n==10) % Sound Power File
                string_th{1}=sprintf('Sound Power Sample');
            end

            app.SelectArrayListBox.Items=string_th;

        end
        
        function SelectFunction(app)
            Items = char(app.SelectFunctionListBox.Items);
            im=app.SelectFunctionListBox.Value;
            [app.mfunction]=find_index(Items,im);

            clear string_th;

            if(app.mfunction==1) % Time History
                string_th{1}=sprintf('Statistics, Time-varying Statistics & Signal Editing Utilities');
                string_th{end+1}=sprintf('Fourier Transform & FFT');
                string_th{end+1}=sprintf('Waterfall FFT');
                string_th{end+1}=sprintf('Power Spectral Density');
                string_th{end+1}=sprintf('Power Transmissibility');
                string_th{end+1}=sprintf('Sine & Damped Sine Curve-fit');
                string_th{end+1}=sprintf('Shock Response Spectrum');
                string_th{end+1}=sprintf('Sound Pressure Level');
                string_th{end+1}=sprintf('Digital Filtering');  
                string_th{end+1}=sprintf('Auto & Cross-correlation');  
                string_th{end+1}=sprintf('Integration, Differentiation');
                string_th{end+1}=sprintf('Relative Displacement between Two Acceleration Time Histories');      
                string_th{end+1}=sprintf('ISO Generic VC, 2631, 10816 & Velocity Spectrum dB');
            end
            if(app.mfunction==2) % PSD
                string_th{1}=sprintf('Overall Level & Spectral Moments');
                string_th{2}=sprintf('Synthesize Time History to Satisfy PSD');     
                string_th{3}=sprintf('SDOF Response to Base Input, VRS & FDS, Stress-Velocity, & Fatigue Various'); 
                string_th{4}=sprintf('SDOF Response to Applied Force, VRS & FDS, Stress-Velocity, & Fatigue Various');              
                string_th{5}=sprintf('PSD Envelope via VRS');
                string_th{6}=sprintf('Calculate Stress PSD from Acceleration Response PSD');
                string_th{7}=sprintf('Time Scaling & Band Splitting');                
            end    
            if(app.mfunction==3) % SRS
                string_th{1}=sprintf('Wavelet Synthesis');
            end    
            app.SelectListBox_signal.Items=string_th;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SelectFunction(app);
            update_list(app);
            tab_change(app);
            assignin('base','myHandle',app);
            app.GenerateTabGroup.SelectedTab=app.GenerateTabGroup.Children(1);
            app.GenerateListBox.Value=app.GenerateListBox.Items(1); 
            clear(app);
            LB_change(app);   
            ChangeNumberCoordinates(app);      
            ChangeTimeOption(app);
            ChangeFilter(app);           
            Sarkani(app);           
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            signal_analysis(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update_list(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                sss=sprintf('clear %s',cell_array{i});
                evalin('base', sss);
            end    
            update_list(app);

        end

        % Button pushed function: AnalyzeButton_StructuralDynamics
        function AnalyzeButton_StructuralDynamicsPushed(app, event)
            SelectSD(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            update_list(app);
            tab_change(app);
        end

        % Button pushed function: AnalyzeButton_Utilities
        function AnalyzeButton_UtilitiesPushed(app, event)
            Items = char(app.SelectAnalysisListBox_Utilities.Items);
            im=app.SelectAnalysisListBox_Utilities.Value;
            [n]=find_index(Items,im);

            if(n==1)
                vibrationdata_unit_conversion_App;
            end    
            if(n==2)
                amplitude_conversion_utilities_a;
            end    
            if(n==3)
                sdof_response_various_a;
            end   
            if(n==4)
                statistical_distributions_ah;
            end                
            if(n==5)
                sine_sweep_parameters_ah;
            end   
            if(n==6)
                sound_editor_ah;
            end            
            if(n==7)
                dboct_a;
            end      
            if(n==8)
                smc_s_016_psd_spl_mpe;         
            end    
            if(n==9)
                Strouhal;         
            end  
        end

        % Callback function
        function AnalyzeButton_2Pushed(app, event)
            select_generate(app);
        end

        % Value changed function: SelectListBox_signal
        function SelectListBox_signalValueChanged(app, event)
            signal_analysis(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_list(app);
        end

        % Button pushed function: PerformExportButton
        function PerformExportButtonPushed(app, event)
            
            try
                FS=app.SelectInputArrayListBox.Value;
                av=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.')
                return;
            end
            
            Items = char(app.listbox_format.Items);
            im=app.listbox_format.Value;
            [n]=find_index(Items,im);
            
            try
                if(n==1) % ascii
                
                    [writefname, writepname] = uiputfile('*.txt','Save data as');
                    writepfname = fullfile(writepname, writefname);
    
                    writematrix(av,writepfname,'Delimiter','\t');
                
                end
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                if(n==2)  % excel
                
                    [writefname, writepname] = uiputfile('*.xls','Save model as Excel file');
                    writepfname = fullfile(writepname, writefname);
                
                    writematrix(av,writepfname);
                
                end
                if(n==3) % mat
                
                    [writefname, writepname] = uiputfile('*.mat','Save data as');
                    
                    pattern = '.mat';
                    replacement = '';
                    sname=regexprep(writefname,pattern,replacement);
                    ss=sprintf('%s',sname);
                    ss1=sprintf('%s',sname);
                      
                    FS2=FS;        
                    FS2=evalin('base',FS2);
                               
                    out1=sprintf('%s=FS2;',ss);
                    
                    clear ss;
                    
                    eval(out1);
                    
                    elk=sprintf('%s%s',writepname,writefname);
                    
                    save(elk,ss1); 
    
                end
                msgbox('Export complete');
            catch
                warndlg('Export failed');
                return;
            end

        end

        % Callback function
        function SelectSignalListBoxValueChanged(app, event)
            select_generate(app);
        end

        % Value changed function: SelectAnalysisListBox
        function SelectAnalysisListBoxValueChanged(app, event)
            SelectSD(app);
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);

                   app.THM = readmatrix(filename);

                   msgbox('File read');              

            catch
                   warndlg('Read unsuccessful');
                   return;
            end

            try
                    [~, name, ~] = fileparts(filename);
            catch
                    warndlg('ref 1')
            end

            try
                    assignin('base',name,app.THM);
            catch
                    warndlg('ref 2')
            end

            sz=size(app.THM);
            
            if(sz(2)>2)
                for i=2:sz(2)
                    nameq=sprintf('%s_%d',name,i-1);
                    assignin('base',nameq,[app.THM(:,1) app.THM(:,i)]);
                end
            end    

            update_list(app);
        end

        % Button pushed function: ReadmatButton
        function ReadmatButtonPushed(app, event)
            try
            
                [filename, pathname] = uigetfile('*.mat');
     
                NAME = [pathname,filename];
                struct=load(NAME);
                structnames = fieldnames(struct, '-full'); % fields in the struct
                
                k=length(structnames);
                
                for i=1:k
                    namevar=strcat('struct.',structnames{i});
                    value=eval(namevar);
                    assignin('base',structnames{i},value);
                end
                
                update_list(app);
                msgbox('Load complete')

            catch

                warndlg('Read failed');
            end    
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            Items = char(app.SelectPlotTypeListBox.Items);
            im=app.SelectPlotTypeListBox.Value;
            [n]=find_index(Items,im);            

            if(n==1)
                plot_time_history_ah;
            end
            if(n==2)
                psd_plot_App;
            end
            if(n==3)
                oaspl_a;
            end    
            if(n==4)
                multiple_plot_App;
            end         
        end

        % Value changed function: SelectTypeListBox
        function SelectTypeListBoxValueChanged(app, event)
            library_read(app);
        end

        % Button down function: ImportTab
        function ImportTabButtonDown(app, event)
            library_read(app);
        end

        % Button down function: LibraryDataTab
        function LibraryDataTabButtonDown(app, event)
            library_read(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            
            Items = char(app.SelectTypeListBox.Items);
            im=app.SelectTypeListBox.Value;
            [p]=find_index(Items,im);
            
            Items = char(app.SelectArrayListBox.Items);
            im=app.SelectArrayListBox.Value;
            [n]=find_index(Items,im);
            
            read_data_function(p,n);

            update_list(app);
        end

        % Value changed function: SelectAnalysisListBox_Utilities
        function SelectAnalysisListBox_UtilitiesValueChanged(app, event)
            AnalyzeButton_UtilitiesPushed(app, event);
        end

        % Button pushed function: CheckforVersionUpdateButton
        function CheckforVersionUpdateButtonPushed(app, event)
            web('https://vibrationdata.wordpress.com/2022/09/28/vibrationdata-matlab-stand-alone-executable-gui-package/');
        end

        % Button pushed function: AnalyzeButton_3
        function AnalyzeButton_3Pushed(app, event)
            Items = char(app.FatigueListBox.Items);
            im=app.FatigueListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                rainflow_app;
            end
            if(n==2)
                FDS_App;
            end    
            if(n==3)
                Steinberg_app;
            end  
        end

        % Value changed function: SelectFunctionListBox
        function SelectFunctionListBoxValueChanged(app, event)
            SelectFunction(app);
        end

        % Value changed function: SelectSystemListBox
        function SelectSystemListBoxValueChanged(app, event)
            SelectSystem(app);
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            GenerateChange(app);            
        end

        % Value changed function: GenerateListBox
        function GenerateListBoxValueChanged(app, event)
            GenerateChange(app);
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplitudeEditField
        function AmplitudeEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PhaseAngledegEditField
        function PhaseAngledegEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: RectifyListBox
        function RectifyListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: NumberofTonesListBox
        function NumberofTonesListBoxValueChanged(app, event)
            clear(app);
            LB_change(app);                      
        end

        % Value changing function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FrequencyHzEditField_2
        function FrequencyHzEditField_2ValueChanging(app, event)
            clear(app);
        end

        % Value changing function: FrequencyHzEditField_dsine
        function FrequencyHzEditField_dsineValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplitudeEditField_dsine
        function AmplitudeEditField_dsineValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DampingRatio1EditField
        function DampingRatio1EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DelayTimeEditField
        function DelayTimeEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: NumberofCoordinatesListBox
        function NumberofCoordinatesListBoxValueChanged(app, event)
            clear(app);
            ChangeNumberCoordinates(app);           
        end

        % Value changed function: SweepTypeListBox
        function SweepTypeListBoxValueChanged(app, event)
            clear(app);
            ChangeTimeOption(app);
        end

        % Value changed function: TimeOptionListBox
        function TimeOptionListBoxValueChanged(app, event)
            clear(app);
            ChangeTimeOption(app);         
        end

        % Value changed function: SweepDirectionListBox
        function SweepDirectionListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: DurationsecEditField_sweep
        function DurationsecEditField_sweepValueChanged(app, event)
            clear(app);
        end

        % Value changed function: SampleRateHzEditField_sweep
        function SampleRateHzEditField_sweepValueChanged(app, event)
            clear(app);
        end

        % Value changed function: NumberofOctavesEditField
        function NumberofOctavesEditFieldValueChanged(app, event)
            clear(app);
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: BaseExcitationArrayEditField
        function BaseExcitationArrayEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_white
        function DurationsecEditField_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_white
        function SampleRateHzEditField_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField
        function StdDevEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: RandomNumberSeedListBox
        function RandomNumberSeedListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: SelectUnitsListBox
        function SelectUnitsListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField_wc
        function StdDevEditField_wcValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_wc
        function DurationsecEditField_wcValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_wc
        function SampleRateHzEditField_wcValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationTaperPercentEditField_cor
        function DurationTaperPercentEditField_corValueChanging(app, event)
            clear(app);
        end

        % Value changed function: T2_ListBox_cor
        function T2_ListBox_corValueChanged(app, event)
            clear(app);
        end

        % Value changed function: T3_ListBox_cor
        function T3_ListBox_corValueChanged(app, event)
            clear(app);
        end

        % Value changed function: BandlimitviaFilteringListBox
        function BandlimitviaFilteringListBoxValueChanged(app, event)
            clear(app);
            ChangeFilter(app);           
        end

        % Value changing function: LowpassFreqHzEditField
        function LowpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: HighpassFreqHzEditField
        function HighpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_two_white
        function DurationsecEditField_two_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField_two_white
        function SampleRateHzEditField_two_whiteValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDev1EditField
        function StdDev1EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDev2EditField
        function StdDev2EditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: CorrelationCoefficientEditField
        function CorrelationCoefficientEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField_add_white
        function StdDevEditField_add_whiteValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed(app, event)
            update_add_white(app);
        end

        % Value changing function: SampleRateEditField_sk
        function SampleRateEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField_sk
        function DurationsecEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StdDevEditField_sk
        function StdDevEditField_skValueChanging(app, event)
            clear(app);
        end

        % Value changed function: SkewnessListBox
        function SkewnessListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: RandomNumberSeedListBox_2
        function RandomNumberSeedListBox_2ValueChanged(app, event)
            clear(app);
        end

        % Value changing function: KurtosisEditField
        function KurtosisEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: 
        % BandlimitviaLowpassFilterListBox_Sarkani_sk
        function BandlimitviaLowpassFilterListBox_Sarkani_skValueChanged(app, event)
            clear(app);
        end

        % Value changed function: BandlimitviaLowpassFilterListBox_Sarkani
        function BandlimitviaLowpassFilterListBox_SarkaniValueChanged(app, event)
            clear(app);
        end

        % Value changing function: betaEditField
        function betaEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: kurtosisEditField
        function kurtosisEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: nEditField
        function nEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: SpecificationMethodListBox
        function SpecificationMethodListBoxValueChanged(app, event)

            Sarkani(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                name=app.OutputNameEditField.Value;
                assignin('base',name,app.THM);
                msgbox('Array Saved');
            catch
            end
        end

        % Button pushed function: CalculateButton_sine
        function CalculateButton_sinePushed(app, event)
             clear(app);
            
            Items = char(app.RectifyListBox.Items);
            im=app.RectifyListBox.Value;
            [irec]=find_index(Items,im);
 
              amp=str2num(app.AmplitudeEditField.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField.Value);
            phase=str2num(app.PhaseAngledegEditField.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
               
            dt=1/sr;
            omega=2*pi*freq;
            phase=phase*pi/180.;
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*sin(omega*TT+phase);
            
            if(irec==2)
                a=abs(a);
            end
            
            t_string='Sine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditField.Value='sine';
            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';  
     

        end

        % Button pushed function: CalculateButton_multi
        function CalculateButton_multiPushed(app, event)
            clear(app);
            
            N=str2num(app.NumberofTonesListBox.Value);
            
            A=char(app.UITable.Data);
            
            B=str2num(A);
            
            peak=B(1:N);
            freq=B((N+1):(2*N)); 
            phase=B((2*N+1):(3*N)); 
            
            phase=phase*pi/180;
            
            tmax=str2num(app.DurationsecEditField.Value);
            sr=str2num(app.SampleRateHzEditField.Value); 
            ylab=app.YaxisLabelEditField.Value;

            dt=1/sr;
            if(max(freq)>(sr/20))
               sr=max(freq)*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               out1=sprintf('%8.4g',sr);
               set(handles.edit_sr,'String',out1);
            end    
       
            omega=2.*pi*freq;
        %    
            np=round(tmax/dt);
            TT=linspace(0,np*dt,(np+1)); 
            a = zeros(1,(np+1));  
        %    
            for i=1:N
                a=a+peak(i)*sin(omega(i)*TT-phase(i));
            end  
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            figure(1);
            plot(TT,a);
            if(i==2)
                tstring='Beat Frequency Effect';
            else
                tstring='Multiple Sine Tones';        
            end
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel(ylab);
            
            app.THM=[TT a];
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditField.Value='sine_tones';
            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';               
        end

        % Button pushed function: CalculateButton_cosine
        function CalculateButton_cosinePushed(app, event)
        
            clear(app);

              amp=str2num(app.AmplitudeEditField_2.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField_2.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
               
            dt=1/sr;
            omega=2*pi*freq;
         
        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    
            np=ceil(tmax/dt);
            TT=linspace(0,np*dt,(np+1));
            a=amp*cos(omega*TT);
            
            t_string='Cosine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.OutputNameEditField.Value='cosine';
            app.SaveButton.Visible='on';  
               
        end

        % Button pushed function: CalculateButton_damped_sine
        function CalculateButton_damped_sinePushed(app, event)
             clear(app);

              amp=str2num(app.AmplitudeEditField_dsine.Value);
             tmax=str2num(app.DurationsecEditField.Value);
             freq=str2num(app.FrequencyHzEditField_dsine.Value);
               sr=str2num(app.SampleRateHzEditField.Value); 
             damp=str2num(app.DampingRatio1EditField.Value);
               td=str2num(app.DelayTimeEditField.Value);
               
            dt=1/sr;
           
            omegan=2*pi*freq;
            omegad=omegan*sqrt(1-damp^2);

        %    
            if(freq>(sr/20))
               sr=freq*20;
               dt=1/sr;
               msgbox('Note: sample rate increased ','Warning','warn');
               sss=sprintf('%8.4g',sr);
               app.SampleRateHzEditField.Value=sss;
            end    

            np=round((tmax+td)/dt);
            TT=linspace(0,(np-1)*dt,np);

        %
            a=zeros(length(TT),1);
            for i=1:length(TT)
                if(TT(i)>td)
                    a(i)=amp*sin(omegad*(TT(i)-td));
                    arg=-omegan*damp*(TT(i)-td);
                    a(i)=a(i)*exp(arg);
                end
            end            

            
            t_string='Damped Sine Time History';

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];

            figure(fig_num);
            plot(app.THM(:,1),app.THM(:,2));
            xlabel('Time (sec)');
            ylabel(app.YaxisLabelEditField.Value);
            title(t_string);
            grid on;

            qq=get(gca,'ylim');
            qy=max(abs(qq));
            ylim([-qy qy]);

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.OutputNameEditField.Value='cosine';
            app.SaveButton.Visible='on';  
            

            app.OutputNameEditField.Value='damped_sine';            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            disp(' ') 
            disp(' * * * * * * ')
            disp(' ')
        
            clear(app);

            N=app.num;
            
            ndirs=app.SweepDirectionListBox.Value;
            
            if(strcmp(ndirs,'up'))
                ndir=1;
            end
            if(strcmp(ndirs,'down'))
                ndir=2;
            end            
            if(strcmp(ndirs,'up & down'))
                ndir=3;
            end               
  
            tmax=app.DurationsecEditField_sweep.Value;
            
            B=app.UITable_sweep.Data;
            
            freq=B(1:N);
            amp=B((N+1):(2*N));
            
            freq=fix_size(freq);
            amp=fix_size(amp);
            
            fa=[freq amp];
            
            fa=sortrows(fa,1);
            
            app.fff=fa(:,1);
            aaa=fa(:,2);
            
            sr=app.SampleRateHzEditField_sweep.Value;
            dt=1/sr;
            
            if(max(app.fff)>(sr/10))
                   sr=max(app.fff)*10;
                   dt=1/sr;
                   msgbox('Note: sample rate increased ');
                   app.SampleRateHzEditField.Value=sr;
            end
           
            f1=app.fff(1);
            f2=app.fff(end);
            oct=log2(f2/f1);

            nto=app.TimeOptionListBox.Value;
            
            disp(' ');
            if(app.ntype==1)
                dur=tmax;
            else
                if(contains(nto,'Duration'))
                    dur=tmax;
                else    
                    dur=(oct/tmax)*60;
                    fprintf(' %7.3g octaves/min ',tmax); 
                end    
            end

            fprintf(' %6.3g octaves \n',oct);
            fprintf(' %7.3g seconds \n',dur);       
            
            app.NumberofOctavesEditField.Visible='on';
            app.NumberofOctavesEditFieldLabel.Visible='on';
            app.NumberofOctavesEditField.Value=oct;
            
            ns=ceil(dur*sr);
            
            if(ndir==3)
                ns=ns*2;
            end
            
            ntimes = ns;
            
            tpi=2.*pi;
            
            if(app.ntype==1)  % linear
                rate=(f2-f1)/dur;
            else              % log
                rate=oct/dur;
            end
            
            maxn=90000000;
            
            if(ntimes>maxn)
                ntimes=maxn;
            %    
                out1=sprintf('Time history truncated to %d points,',maxn);
                out5 = sprintf('or %g sec',maxn*dt);
                sss=strcat(out1,out5);
            %  
                msgbox(sss,'Warning','warn');        
            end
            
            if(ndir==3)
                nt=ntimes/2;
            else
                nt=ntimes;     
            end
            
            TT=linspace(dt,(ntimes+1)*dt,ntimes); 
            
               a = zeros(1,ntimes);
             arg = zeros(1,nt);
            freq = zeros(1,nt);
            
            if(app.ntype==1)  % linear
            %
            % 0.5 factor is necessary to obtain correct number of cycles for linear case.
            %
            %	fspectral= (     rate*t ) + f1;
            %
                fmax=0.5*(f2-f1)+f1;
                freq=linspace(f1,fmax,nt);     
            %
            else  % log
            %  
            %   fspectral = f1*pow(2.,rate*t);
            %
                for i=1:nt
                    arg(i)=-1.+2^(rate*TT(i));
                end   
            end
            
            freq=fix_size(freq);
            TT=fix_size(TT);
            
            if(app.ntype==1) % linear
               arg=tpi*freq.*TT(1:nt);
            else             % log
               arg=tpi*f1*arg/(rate*log(2));  
            end
            
            if(ndir==3)
                   arg1=arg;
                   arg1=fix_size(arg1);
                   arg2=flipud(arg1);       
                   arg=[arg1; arg2];
            end 
            
            ntimes=length(a);
            
            if(app.ntype==1) % linear
                spectral=linspace(f1,f2,nt); 
            else 
                f1=log10(f1);
                f2=log10(f2);
                spectral=logspace(f1,f2,nt);         
            end
            
            limit=length(app.fff)-1;
            amplitude=zeros(1,nt);
            
            for i=1:nt
               for j=1:limit
                   if(app.fff(j)<=spectral(i) && app.fff(j+1)>=spectral(i))
                        x=spectral(i)-app.fff(j);
                        L=app.fff(j+1)-app.fff(j);
                        c2=x/L;
                        c1=1-x/L;
                        amplitude(i)=c1*aaa(j)+c2*aaa(j+1);
                        break;
                   end
               end
            end
            
            if(ndir==3)
                a(1:nt)=sin(arg(1:nt));  
                
                diff_rec=1.0e+90;
                 
                for i=0:359
                    theta=pi*i/180;
            
                    a1=a(nt-1);
                    a2=a(nt);
                    a3=sin(arg(nt+1)+theta);
                    a4=sin(arg(nt+2)+theta);        
            
                    ab=[a1 a2 a3 a4];
                    aa=detrend(ab);
                    
                    diff=norm(aa);
                    
                    if(diff<diff_rec)
                        diff_rec=diff;
                        psi=theta;
                    end
                    
                end
            %    psi
            
                a((nt+1):ntimes)=sin(arg((nt+1):ntimes)+psi);
            else
                a=sin(arg);
            end
            %
            
            if(ndir==3)
                   amp1=amplitude;
                   amp1=fix_size(amp1);
                   amp2=flipud(amp1);       
                   amplitude=[amp1; amp2];    
            end
            
            a=fix_size(a);
            amplitude=fix_size(amplitude);
            
            a(1)=0.;
            a=a.*amplitude;
            TT=TT-dt;
            
            TT=fix_size(TT);
            a=fix_size(a);
            
            if(ndir==2)
                a=flipud(a);
            end
            
            figure(1);
            plot(TT,a);
            tstring='Sine Sweep';
            title(tstring);
            grid on;
            xlabel('Time (sec)');
            ylabel('Amplitude');
            
            app.signal=[TT a];            
            
            fig_num=2;
            
            t=TT;    
            
            numt=length(t);
            
            %
            %   positive slope zero crossing times for input
            %
            
            tindex=zeros(numt,1);
            tz=zeros(numt,1);
            
            k=1;
            for i=2:numt
                if( a(i) > a(i-1) && a(i) > 0 && a(i-1) <=0)
                    tindex(k)=i;
                    L=a(i)-a(i-1);
                    c1=a(i)/L;
                    c2=1-c1;
                    tz(k)=c1*t(i-1)+c2*t(i);
                    k=k+1;
                end
            end
            
            nt=k-1;   % number of positive slope zero crossings
            
            %% step=round(str2num(get(handles.edit_step,'String')));   % with sliding window
            
            step=ceil((1/app.fff(1))/dt)/25;
            
            if(step<20)
                step=20;
            end
 
            
            m=1;
            for i=(step+1):nt
                m=m+1;
            end
            m=m-2;  % leave
             
            aa=zeros(m,1);
            f=zeros(m,1);
            
            nspec=app.CalculateSpectrumFunctionListBox.Value;
                
            if(strcmp(nspec,'Yes'))
            
                progressbar;
            
                m=1;
                
                for i=(step+1):nt
                
                    progressbar(i/nt);
                
                    kk=tindex(i);
                    jj=tindex(i-step);
                    T=(tz(i)-tz(i-step))/step;
                    f(m)=1/T;
                    aa(m)=max(abs(a(jj:kk)));
                
                    m=m+1;
                end
            
                pause(0.2);
                progressbar(1);
                
                ppp=[f aa];
                t_string='Sine Sweep Spectral Function';
                x_label='Frequency (Hz)';
                y_label='Accel (G)';
                
                fmin=f(1);
                fmax=f(end);
                
                md=5;
                
                [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
                
            end
            
            name=app.OutputArrayNameEditField.Value;
            
            assignin('base',name, app.signal); 

            app.BaseExcitationArrayEditField.Value=name;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
     disp(' ');
            disp(' * * * * * * * ');
            disp(' ');
        
            clear(app);
            
            iu=1;
            
            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            Q=str2num(app.AmplificationFactorQEditField.Value);
            FS=app.BaseExcitationArrayEditField.Value;

            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end 
            
            damp=1/(2*Q);
            
            dt=mean(diff(app.THM(:,1)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);
            %
            %  SRS engine
            %
            
            a_pos=zeros(1,1);
            a_neg=zeros(1,1);
            rd_pos=zeros(1,1);
            rd_neg=zeros(1,1);
            
            yy=app.THM(:,2);
            
            for j=1:1
            %
                disp(' ')
                disp(' Calculating absolute acceleration');
            %
                [a_resp,a_pos(j),a_neg(j)]=arbit_engine(a1(j),a2(j),b1(j),b2(j),b3(j),yy);

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ')
                disp(' Calculating relative displacement');
            %
                [rd_resp,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),yy);

                if(iu==1)
                    scale=386;
                end
                if(iu==2)
                    scale=9.81*1000;        
                end
                if(iu==3)
                    scale=1000;        
                end    
            %
                rd_resp=rd_resp*scale;
                rd_pos(j)=rd_pos(j)*scale;
                rd_neg(j)=rd_neg(j)*scale;

            end
            %
            if(iu==1 || iu==2)
                big_string=sprintf(' Acceleration Response (G) \n');
            else
                big_string=sprintf(' Acceleration Response (m/sec^2) \n');
            end
            
            string1=sprintf('\n\n max=%8.4g',a_pos(1));
            string2=sprintf('\n min=%8.4g',-a_neg(1));
    
            big_string=strcat(big_string,string1);
            big_string=strcat(big_string,string2);
                       
            if(iu==1)
                string8=sprintf('\n\n Relative Displacement (in) \n');    
            else
                string8=sprintf('\n\n Relative Displacement (mm) \n');    
            end
                
            string9=sprintf('\n\n max=%7.3g',rd_pos(1));
            string10=sprintf('\n min=%7.3g',rd_neg(1));

            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);            
            
            fprintf('\n Q=%g \n',Q);
            
            app.ResultsTextArea_sweep.Value=big_string;
            app.ResultsTextArea_sweep.Visible='on';
            app.ResultsTextAreaLabel_sweep.Visible='on';            
            
            disp(' ');
            disp(big_string);
              
            fig_num=1;            
  
            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),a_resp);
            xlabel(' Time(sec) ');
            legend('Base Input','Response');
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')    
            end
            grid on;
            title('Acceleration Time History');
            
            if(iu==1 || iu==2)
                    ylabel1='Accel (G)';
                    ylabel2='Accel (G)'; 
            else
                    ylabel1='Accel (m/sec^2)';
                    ylabel2='Accel (m/sec^2)';     
            end
            
            xlabel2='Time (sec)';
            
            t_string1=('Base Input');
            t_string2=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
            
            data1=app.THM;
            data2=[app.THM(:,1) a_resp];
            
            [fig_num]=...
                subplots_two_linlin_two_titles_scale_same(fig_num,xlabel2,ylabel1,ylabel2,...
                                                              data1,data2,t_string1,t_string2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.THM(:,1),rd_resp);
            out1=sprintf('Relative Displacement fn=%g Hz  Q=%g',fn,Q);
            title(out1);
            xlabel(' Time(sec) ')
            if(iu==1)
                ylabel('Rel Disp (in)')
            else
                ylabel('Rel Disp (mm)')    
            end
            grid on;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            name_a=app.AccelResponseArrayEditField.Value;
            name_rd=app.RelDispResponseArrayEditField.Value;
            
            assignin('base',name_a, [app.THM(:,1) a_resp]);
            assignin('base',name_rd, [app.THM(:,1) rd_resp]);   
         
            iss=strtrim(app.CalculateListBox.Value);
            
            if(strcmp(iss,'Time Histories Only'))
                ijk=1;
            end
            if(strcmp(iss,'Transmissibility'))
                ijk=2;
            end
            if(strcmp(iss,'Transmissibility & Phase'))
                ijk=3;
            end
                        
            if(ijk>=2)
                a=yy;
                b=a_resp;
                
                nt=length(a);
                
                %
                %   positive slope zero crossing times for input
                %
                tindex=zeros(nt,1);
                tz=zeros(nt,1);
                
                t=app.THM(:,1);
                
                k=1;
                for i=2:nt
                    if( a(i) > a(i-1) && a(i) > 0 && a(i-1) <=0)
                        tindex(k)=i;
                        L=a(i)-a(i-1);
                        c1=a(i)/L;
                        c2=1-c1;
                        tz(k)=c1*t(i-1)+c2*t(i);
                        k=k+1;
                    end
                end
                
                nt=k-1;   % number of positive slope zero crossings
                
                step=ceil((1/app.fff(1))/dt)/25;
            
                if(step<20)
                    step=20;
                end
 
                m=1;
                for i=(step+1):nt
                    m=m+1;
                end
                m=m-2;  % leave
                 
                aa=zeros(m,1);
                bb=zeros(m,1);
                trans=zeros(m,1);
                f=zeros(m,1);
                phase=zeros(m,1);
                    
                progressbar;
                
                m=1;
                for i=(step+1):nt
                    
                    progressbar(i/nt);
                    
                    kk=tindex(i);
                    jj=tindex(i-step);
                    T=(tz(i)-tz(i-step))/step;
                    f(m)=1/T;
                    aa(m)=std(a(jj:kk));
                    bb(m)=std(b(jj:kk));
                    trans(m)=bb(m)/aa(m);
                    
                    if(ijk==3)
                        
                        tpi=2*pi;
                        
                        omega=tpi*f(m);
                    
                        [~,~,~,~,~,x3a,~]=sine_lsf_function(a(jj:kk),t(jj:kk),omega);
                        [~,~,~,~,~,x3b,~]=sine_lsf_function(b(jj:kk),t(jj:kk),omega);
                                                
                        phase(m)=x3a-x3b;
                        
                        if(phase(m)<0.)
                            phase(m)=phase(m)+tpi;
                        end
                    
                    end    
                    
                    m=m+1;
                end
                
                pause(0.2);
                progressbar(1);
                
                phase=(180/pi)*phase;
                
                figure(fig_num);
                fig_num=fig_num+1;
                
                plot(f,trans);
                title('Sine Sweep Transmissibility');
                xlabel('Frequency (Hz)');
                
                k=1;
                
                if( k>=1 )
                    ylabel('Trans (G/G)');
                else
                    ylabel('Trans');
                end    
                grid on;
                
                xl = xlim;
                fmin=xl(1);
                fmax=xl(2);
                
                trans=[f trans];
                
                if(ijk==3)
                    
                    t_string='Sine Sweep Magnitude & Phase';
                    
                    ff=f;
                    FRF_p=phase;
                    FRF_m=trans(:,2);
                    ylab='Accel (G)';
                    md=4;
                    
                    [fig_num]=...
                    plot_magnitude_phase_function_linear(fig_num,t_string,fmin,fmax,ff,FRF_p,FRF_m,ylab,md);
                    
                end
                
                [fmax,amax]=find_max(trans);
                
                disp(' ');
                disp('* * * * * * * * * ');
                disp(' ');
                
                fprintf('\n  Maximum Transmissibility: (%8.4g Hz, %8.4g G/G) \n\n',amax,fmax);
                                         
            end            
        end

        % Button pushed function: CalculateButton_sk
        function CalculateButton_skPushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateEditField_sk.Value);
            tmax=str2num(app.DurationsecEditField_sk.Value);
            YS=app.YaxisLabelEditField_sk.Value;
            sigma=str2num(app.StdDevEditField_sk.Value)

            kt=str2num(app.kurtosisEditField.Value);
            sk=str2num(app.SkewnessListBox.Value);

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            Items = char(app.SkewnessListBox.Items);
            im=app.SkewnessListBox.Value;
            [n]=find_index(Items,im);

            skgoal = str2num(app.SkewnessListBox.Value);
            ktgoal = str2num(app.KurtosisEditField.Value);       

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);

            %%%%%

            app.num=np;

            skerror=1.0e+20;
            kterror=skerror;

            x=zeros(app.num,1);
            
            for i=1:app.num      
                x(i)=(i-1)*dt;
            end
            
            if(app.nf==1)
            %
            %    iband:   1=lowpass  2=highpass  3=bandpass 
            %
            %    iphase=1  for refiltering for phase correction
            %          =2  for no refiltering
                  iband=1;
                  fl=sr/10;
                  fh=0;
                  iphase=2;
            end    
            
            for nt=1:7
            
                a=randn(app.num,1);
                y=zeros(app.num,1);
            
                if(app.nf==1)
            %    
                  [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);
            %      
                end      
               
                if(skgoal==0)  % skew = 0
                    ex = 0.842*(ktgoal-1.36)^0.378;
            %
                    for i=1:app.num
                        y(i)=sign(a(i))*(abs(a(i))^ex);
                    end
                else
                    [p1,p2,skgoal]=skewness_kurtosis_coefficients(n,app.nf);       
                end
                
                if(skgoal>0)  % skew >= 0.1
                    LK=log10(ktgoal);
                    exp= p1(1)*LK^2+p1(2)*LK+p1(3);
                    exn= p2(1)*LK^2+p2(2)*LK+p2(3);
                
                    for i=1:app.num
                     
                        if(a(i)>0)
                            y(i)=a(i)^exp;
                        else
                            y(i)=-(abs(a(i))^exn);            
                        end    
                    end 
                end    
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                sd=std(y);
                mu=mean(y);
            
                y=y-mu;
            
                y=y*sigma/sd;
            
                [mu,sd,~,sk,kt]=kurtosis_stats(y);
                
                if( abs(sk-skgoal)<skerror && abs(kt-ktgoal)<kterror)
                    skerror=abs(sk-skgoal);
                    kterror=abs(kt-ktgoal);
                    
                    ybest=y;
                    
                    mu_best=mu;
                    sd_best=sd;  
                    sk_best=sk;
                    kt_best=kt;
                end
            
            end   

            %%%%%

            a=ybest;

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            t_string=sprintf(' mean=%4.2f  std=%g  skewness=%4.2g  kurtosis=%4.2g',mu_best,sd_best,sk_best,kt_best);
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_random_sk.Value=ss;

            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';

            name=app.OutputNameEditField_Sarkani.Value;
            assignin('base',name,app.THM);            
        end

        % Button pushed function: CalculateButton_Sarkani
        function CalculateButton_SarkaniPushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateEditField_sk.Value);
            duration=str2num(app.DurationsecEditField_sk.Value);
            y_label=app.YaxisLabelEditField_sk.Value;
            sigma=str2num(app.StdDevEditField_sk.Value);

            Items = char(app.SpecificationMethodListBox.Items);
            im=app.SpecificationMethodListBox.Value;
            [nmeth]=find_index(Items,im);

            if(nmeth==1)
                kt=str2num(app.kurtosisEditField.Value);  
                [beta,n,ktr]=Sarkani_table(kt);
            else
                beta=str2num(app.betaEditField.Value);
                n=str2num(app.nEditField.Value);
            end
            
            dt=1/sr;
            
            nt=floor(duration/dt);
            
            t=zeros(nt,1);
            for i=1:nt
               t(i)=(i-1)*dt; 
            end
            
            mu=0;
            
            [X]=simple_white_noise(mu,sigma,nt);
           
            Items = char(app.BandlimitviaLowpassFilterListBox_Sarkani.Items);
            im=app.BandlimitviaLowpassFilterListBox_Sarkani.Value;
            [nff]=find_index(Items,im);
            
            if(nff==1)
                fl=sr/10;
                fh=0;
                iband=1;
                iphase=2;
                [X,~,~,~]=Butterworth_filter_function_alt(X,dt,iband,fl,fh,iphase);    
            end
            
            Y=zeros(nt,1);
            for i=1:nt
                Y(i)=X(i)+beta*sign(X(i))*(abs(X(i))^n);
            end
            %        
            Y=sigma*Y/std(Y);
         
            Y=fix_size(Y);
            t=fix_size(t);
                        
            app.signal=[t Y];

            fig_num=1;
            app.THM=app.signal;
            x_label='Time (sec)';

            nbars=31;
        
            t_string=sprintf('Random Time History');
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);

            a=Y;
            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_random_sk.Value=ss;

            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';  

            name=app.OutputNameEditField_Sarkani.Value;
            assignin('base',name,app.THM);             
        end

        % Button pushed function: CalculateButton_pink
        function CalculateButton_pinkPushed(app, event)
            clear(app);

            sr=str2num(app.SampleRateEditField_sk.Value);
            tmax=str2num(app.DurationsecEditField_sk.Value);
            YS=app.YaxisLabelEditField_sk.Value;
            sigma=str2num(app.StdDevEditField_sk.Value);

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np);       
            np=length(TT);

            PP = pinknoise(np);

        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %  fade in & out
        %
            na=floor(0.5/dt);
            nb=np-na;
            delta=np-nb;
        %
            for i=1:na
                arg=pi*(( (i-1)/(na-1) )+1); 
                PP(i)=PP(i)*0.5*(1+(cos(arg)));
            end
        %
            for i=nb:np
                arg=pi*( (i-nb)/delta );
                PP(i)=PP(i)*(1+cos(arg))*0.5;
            end
        %
            TT=fix_size(TT);
            PP=fix_size(PP);
        %
            PP=PP-mean(PP);
            PP=PP*sigma/std(PP);  

            pink_time_history=[TT PP];

            name=app.OutputNameEditField_pink.Value;
            assignin('base',name,pink_time_history);

            figure(1);
            plot(TT,PP);
            title('Pink Noise Time History');
            xlabel('Time (sec)');
            ylabel(YS);
            grid on;
     
            app.ResultsTextAreaLabel_random_sk.Visible='on';
            app.ResultsTextArea_random_sk.Visible='on';                     
        end

        % Value changed function: OutputNameEditField_sk
        function OutputNameEditField_skValueChanged(app, event)
            value = app.OutputNameEditField_sk.Value;
            
        end

        % Button pushed function: CalculateButton_white_noise_basic
        function CalculateButton_white_noise_basicPushed(app, event)
            clear(app);

            sigma=str2num(app.StdDevEditField.Value);
            tmax=str2num(app.DurationsecEditField_white.Value);
            sr=str2num(app.SampleRateHzEditField_white.Value); 

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);
            
            [a]=simple_white_noise(0,sigma,np);
            
        %
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              a=(a-ave)*sss;        
                
            end    
            
            if(app.nf==3)
               t_string='White Noise';
            end     

       %%%%%

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            name=app.OutputNameEditField_white.Value;
            assignin('base',name,app.THM);


            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';
        end

        % Button pushed function: CalculateButton_white_noise_corrected
        function CalculateButton_white_noise_correctedPushed(app, event)
            clear(app);

            Items = char(app.SelectUnitsListBox.Items);
            im=app.SelectUnitsListBox.Value;
            [iunit]=find_index(Items,im);

            sigma=str2num(app.StdDevEditField_wc.Value);
            tmax=str2num(app.DurationsecEditField_wc.Value);
            sr=str2num(app.SampleRateHzEditField_wc.Value);
            
            npe=str2num(app.DurationTaperPercentEditField_cor.Value);           

            Items = char(app.T2_ListBox_cor.Items);
            im=app.T2_ListBox_cor.Value;
            [I2]=find_index(Items,im);

            Items = char(app.T3_ListBox_cor.Items);
            im=app.T3_ListBox_cor.Value;
            [I3]=find_index(Items,im);           

            %%%%%%

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
            tt=TT;
        
            clear length;
            np=length(TT);
            
            [a]=simple_white_noise(0,sigma,np);
            
        %
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              y=(a-ave)*sss;        
                
            end    
            
            if(npe>0)
                [y]=half_cosine_fade_perc(y,npe);
            end
        
            a=y;
            
            v=dt*cumtrapz(a);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            tt=fix_size(tt);

            % In Between
            
            if(I2==2)
                v=v-mean(v);    
            end    
            if(I2==3)
                v=detrend(v);        
            end 
            if(I2==4) 
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end 
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            if(npe>0)
                [v]=half_cosine_fade_perc(v,npe);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2

            d=dt*cumtrapz(v);
            
            if(I3==2)
                d=d-mean(d);    
            end    
            if(I3==3)
                d=detrend(d);        
            end 
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end 
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end   
            
            if(npe>0)
                [d]=half_cosine_fade_perc(d,npe);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (ips)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/s^2)';
                vy='Vel (cm/s)';
                dy='Disp (mm)';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            [vv]=differentiate_function(d,dt);
            [aa]=differentiate_function(vv,dt);
            
            if(iunit==1) % G, in/sec, in
                aa=aa/386;
            end
            if(iunit==2) % G, cm/sec, mm
                vv=vv/10;
                aa=aa/(9.81*1000);
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vv=vv/10;
                aa=aa/1000;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            vvv=dt*cumtrapz(aa);
            ddd=dt*cumtrapz(vvv);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(iunit==1) % G, in/sec, in
                vvv=vvv*386;
                ddd=ddd*386;
            end
            if(iunit==2) % G, cm/sec, mm
                vvv=vvv*9.81*100;
                ddd=ddd*9.81*1000;
            end
            if(iunit==3) % m/sec^2, cm/sec, mm
                vvv=vvv*10;
                ddd=ddd*1000;
            end

            tt=fix_size(tt);
            aa=fix_size(aa);
            vvv=fix_size(vvv);
            ddd=fix_size(ddd);

            app.accel=[tt a];
            app.vel=[tt vvv];
            app.dispx=[tt ddd];  
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fig_num=1;
            
            tstart=tt(1);
            tend=1.001*tt(np);
            
            hp=figure(fig_num);
            fig_num=fig_num+1;
            subplot(3,3,1);
            plot(tt,a);
            ylabel(ay);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,2);
            plot(tt,aa);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,3);
            plot(tt,aa);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,4);
            plot(tt,v);
            ylabel(vy);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,5);
            plot(tt,vv);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,6);
            plot(tt,vvv);
            xlim([tstart tend]);
            grid on;
            
            subplot(3,3,7);
            plot(tt,d);
            xlim([tstart tend]);
            ylabel(dy);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,8);
            plot(tt,d);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            subplot(3,3,9);
            plot(tt,ddd);
            xlim([tstart tend]);
            grid on;
            xlabel('Time(sec)');
            
            set(hp, 'Position', [0 0 850 650]);

            %%%%%%

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);
            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';

        end

        % Button pushed function: CalculateButton_two_correlated_white
        function CalculateButton_two_correlated_whitePushed(app, event)
            clear(app);

            YS=app.YaxisLabelEditField_white_2.Value;

            fig_num=1;
             
            tmax=str2num(app.DurationsecEditField_two_white.Value);    
            sigma1=str2num(app.StdDev1EditField.Value);   
            sigma2=str2num(app.StdDev2EditField.Value);   
            sr=str2num(app.SampleRateHzEditField_two_white.Value);
             
            dt=1./sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
          
        %%%    [a] = normrnd_function(sigma,np);
        
            clear length;
            np=length(TT);
            
            a=randn(np,1);
            a=fix_size(a);
            a=a-mean(a);
            a=a*sigma1/std(a);
            
            b=randn(np,1);
            b=fix_size(b);
            b=b-mean(b);

            b=b*sigma2/std(b);    
            
        %%%%%%
     
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);     
                [b,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase); 
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma1/stddev;
              a=(a-ave)*sss;        
                
              ave=mean(b);
              stddev=std(b);
              sss=sigma2/stddev;
              b=(b-ave)*sss;                  
            end    
            
            if(app.nf==3)
               t_string='White Noise';
            end     
   
        %%%%%%

            rho=str2num(app.CorrelationCoefficientEditField.Value);
            c=rho*a+sqrt(1-rho^2)*b;
            
            clear b;

            figure(fig_num);
            fig_num=fig_num+1;
            plot(TT,a,TT,c);
            title('White Noise');
            legend('First','Second');
            grid on;
            xlabel('Time (sec)');
            ylabel(YS);
            
            nnum=np;
            [xc,xmax,tmax]=cross_correlation_function(a,c,nnum,dt);
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %    
            [rho]=Pearson_coefficient(a,c);
        %
        
            disp(' ');
            disp(' Covariance Matrix ');
            disp(' ');
            
            cvm = cov(a,c);

            ss='Covariance Matrix';
            ss1=sprintf(' %7.3g \t  %7.3g ',cvm(1,1),cvm(1,2));
            ss2=sprintf(' %7.3g \t  %7.3g ',cvm(2,1),cvm(2,2));
            ss=sprintf('%s\n\n %s \n %s ',ss,ss1,ss2);
        %
        
            figure(fig_num);
            fig_num=fig_num+1;
            plot(a,c,'bo','MarkerSize',3);
            grid on;
            xlabel('First Signal Amplitude');
            ylabel('Second Signal Amplitude');
            out2=sprintf('Scatter Plot, Pearson Coefficient= %6.3g',rho);
            title(out2);
            axis square
            aa=max(abs(a));
            cc=max(abs(c));
            qmax=max([aa cc]);
            [ymax,~,~,iflag]=ytick_linear_double_sided(qmax);
            if(iflag==1)
                qmax=ymax;
            end
            xlim([-qmax qmax]);
            ylim([-qmax qmax]); 
           
        %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(xc(:,1),xc(:,2));
            xlabel(' Delay(sec) ');
            ylabel('Rxy');
            out1=sprintf(' Cross-correlation  Max Abs = %8.4g at %8.4g sec',xmax,tmax);
            title(out1);
            disp(out1);      
            grid on;
         
            xlabel2='Time (sec)';
            ylabel1=YS;
            ylabel2=YS;
            
            TT=fix_size(TT);
            a=fix_size(a);
            c=fix_size(c);
            
            data1=[TT a];
            data2=[TT c];
            data3=[TT a c];
            t_string1='First Signal';
            t_string2='Second Signal';
            
            nbars=31;                                      
                                              
            [fig_num]=plot_two_time_histories_histograms_alt(fig_num,xlabel2,...
                            ylabel1,ylabel2,data1,data2,t_string1,t_string2,nbars);    
                
            out1=sprintf('Pearson product-moment \ncorrelation coefficient = \n   %8.4g ',rho);
            ss=sprintf('%s \n\n%s',ss,out1); 
        
            prefix=app.OutputNameEditField_two_white.Value;    
            
            s1=sprintf('%s_1',prefix);
            s2=sprintf('%s_2',prefix);    
            s3=sprintf('%s_1_2',prefix);    
        
            assignin('base', s1,data1);   
            assignin('base', s2,data2);  
            assignin('base', s3,data3); 
            
            out1=sprintf(' Calculation Complete. Output arrays: \n  %s \n  %s \n  %s',s1,s2,s3);    
            msgbox(out1);
               
            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';  
        end

        % Button pushed function: CalculateButton_add_white_noise
        function CalculateButton_add_white_noisePushed(app, event)
            clear(app);

            try
                FS=app.SelectInputArrayListBox_add_white.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            sigma=str2num(app.StdDevEditField_add_white.Value);
         
            TT=app.THM(:,1);
            np=length(TT);

            dt=mean(diff(app.THM(:,1)));
            sr=1/dt;
            
            [a]=simple_white_noise(0,sigma,np);

            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %

              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HighpassFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                iphase=2;
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              a=(a-ave)*sss;        
                
            end    

       %%%%%

            app.THM(:,2)=app.THM(:,2)+a;

            fig_num=1;
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
            t_string='Composite Signal';
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            name=app.OutputNameEditField_add_white.Value;
            assignin('base',name,app.THM);


            app.ResultsTextArea_white.Value=ss;
            app.ResultsTextArea_white.Visible='on';
            app.ResultsTextAreaLabel_white.Visible='on';
              
        end

        % Button pushed function: SaveButton_cor
        function SaveButton_corPushed(app, event)
            Items = char(app.ExportFunctionListBox_cor.Items);
            im=app.ExportFunctionListBox_cor.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel;
            end    
            if(n==2)
                data=app.vel;
            end  
            if(n==3)
                data=app.dispx;
            end              
           
            name=app.OutputNameEditField_cor.Value;
            assignin('base',name,data);

            msgbox('Save Complete');

        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethod]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox_add_white.Value=name;
            end    
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            vibrationdata_fourier_transform_ah;
        end

        % Button pushed function: SDOFResponsetoBaseInputButton
        function SDOFResponsetoBaseInputButtonPushed(app, event)
            sdof_response_time_domain;             
        end

        % Button pushed function: AnalyzeButton_4
        function AnalyzeButton_4Pushed(app, event)
            Items = char(app.AnalysisListBox.Items);
            im=app.AnalysisListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                cylinder_ring_frequency_a;
            end
            if(n==2)
                acoustic_seismic_coupling_a;
            end    
            if(n==3)
                vibrationdata_doppler_App_1;
            end    
            if(n==4)
                sound_pressure_intensity_a;
            end    
            if(n==5)
                aerodynamic_flow_a;  
            end    
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vibrationdataaltUIFigure = uifigure('Visible', 'off');
            app.vibrationdataaltUIFigure.Name = 'vibrationdata alt';
            app.vibrationdata_altver44byTomIrvineemailtomirvinemailorgLabel = uilabel(app.vibrationdataaltUIFigure);
            app.vibrationdata_altver44byTomIrvineemailtomirvinemailorgLabel.Text = 'vibrationdata_alt     ver 4.4  by Tom Irvine       email:  tom@irvinemail.org';
            app.TabGroup = uitabgroup(app.vibrationdataaltUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SignalProcessingTab = uitab(app.TabGroup);
            app.SignalProcessingTab.Title = 'Signal Processing';
            app.SelectAnalysisLabel = uilabel(app.SignalProcessingTab);
            app.SelectAnalysisLabel.Text = 'Select Analysis';
            app.SelectListBox_signal = uilistbox(app.SignalProcessingTab);
            app.SelectListBox_signal.Items = {'Statistics & Signal Editing Utilities', 'Fourier Transform & FFT', 'Waterfall FFT', 'Power Spectral Density', 'Power Transmissibility', 'Sine & Damped Sine Curve-fit', 'Shock Response Spectrum', 'Sound Pressure Level', 'Digital Filtering', 'Auto & Cross-Correlation'};
            app.SelectListBox_signal.ValueChangedFcn = createCallbackFcn(app, @SelectListBox_signalValueChanged, true);
            app.SelectListBox_signal.Value = 'Power Transmissibility';
            app.AnalyzeButton = uibutton(app.SignalProcessingTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SelectFunctionListBoxLabel = uilabel(app.SignalProcessingTab);
            app.SelectFunctionListBoxLabel.Text = 'Select Function';
            app.SelectFunctionListBox = uilistbox(app.SignalProcessingTab);
            app.SelectFunctionListBox.Items = {'Time History', 'PSD', 'SRS'};
            app.SelectFunctionListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFunctionListBoxValueChanged, true);
            app.SelectFunctionListBox.Value = 'Time History';
            app.StructuralDynamicsTab = uitab(app.TabGroup);
            app.StructuralDynamicsTab.Title = 'Structural Dynamics';
            app.SelectAnalysisListBoxLabel = uilabel(app.StructuralDynamicsTab);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox = uilistbox(app.StructuralDynamicsTab);
            app.SelectAnalysisListBox.Items = {'Item 1'};
            app.SelectAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBoxValueChanged, true);
            app.AnalyzeButton_StructuralDynamics = uibutton(app.StructuralDynamicsTab, 'push');
            app.AnalyzeButton_StructuralDynamics.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_StructuralDynamicsPushed, true);
            app.AnalyzeButton_StructuralDynamics.Text = 'Analyze';
            app.SelectSystemListBoxLabel = uilabel(app.StructuralDynamicsTab);
            app.SelectSystemListBoxLabel.Text = 'Select System';
            app.SelectSystemListBox = uilistbox(app.StructuralDynamicsTab);
            app.SelectSystemListBox.Items = {'SDOF', 'Two-dof', 'Beam', 'Plate'};
            app.SelectSystemListBox.ValueChangedFcn = createCallbackFcn(app, @SelectSystemListBoxValueChanged, true);
            app.SelectSystemListBox.Value = 'SDOF';
            app.GenerateTab = uitab(app.TabGroup);
            app.GenerateTab.Title = 'Generate';
            app.GenerateTabGroup = uitabgroup(app.GenerateTab);
            app.SignalIndexTab = uitab(app.GenerateTabGroup);
            app.SignalIndexTab.Title = 'Signal Index';
            app.SelectSignalLabel = uilabel(app.SignalIndexTab);
            app.SelectSignalLabel.Text = 'Select Signal';
            app.GenerateListBox = uilistbox(app.SignalIndexTab);
            app.GenerateListBox.Items = {'index', 'sine, multiple sine tones, damped sine & cosine', 'sine sweep', 'white noise various', 'random with skewness & kurtosis, Sarkani random, pink noise'};
            app.GenerateListBox.ValueChangedFcn = createCallbackFcn(app, @GenerateListBoxValueChanged, true);
            app.GenerateListBox.Value = 'random with skewness & kurtosis, Sarkani random, pink noise';
            app.PerformAnalysisButton = uibutton(app.SignalIndexTab, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.aTab = uitab(app.GenerateTabGroup);
            app.aTab.Title = 'a';
            app.TabGroup_2 = uitabgroup(app.aTab);
            app.SineTab = uitab(app.TabGroup_2);
            app.SineTab.Title = 'Sine';
            app.AmplitudeEditFieldLabel = uilabel(app.SineTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SineTab, 'text');
            app.AmplitudeEditField.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanging, true);
            app.FrequencyHzEditFieldLabel = uilabel(app.SineTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SineTab, 'text');
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanging, true);
            app.PhaseAngledegEditFieldLabel = uilabel(app.SineTab);
            app.PhaseAngledegEditFieldLabel.Text = 'Phase Angle (deg)';
            app.PhaseAngledegEditField = uieditfield(app.SineTab, 'text');
            app.PhaseAngledegEditField.ValueChangingFcn = createCallbackFcn(app, @PhaseAngledegEditFieldValueChanging, true);
            app.PhaseAngledegEditField.Value = '0';
            app.RectifyListBoxLabel = uilabel(app.SineTab);
            app.RectifyListBoxLabel.Text = 'Rectify';
            app.RectifyListBox = uilistbox(app.SineTab);
            app.RectifyListBox.Items = {'No', 'Yes'};
            app.RectifyListBox.ValueChangedFcn = createCallbackFcn(app, @RectifyListBoxValueChanged, true);
            app.RectifyListBox.Value = 'No';
            app.CalculateButton_sine = uibutton(app.SineTab, 'push');
            app.CalculateButton_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sinePushed, true);
            app.CalculateButton_sine.Text = 'Calculate';
            app.MultipleSineTonesTab = uitab(app.TabGroup_2);
            app.MultipleSineTonesTab.Title = 'Multiple Sine Tones';
            app.NumberofTonesListBoxLabel = uilabel(app.MultipleSineTonesTab);
            app.NumberofTonesListBoxLabel.Text = {'Number '; 'of Tones'};
            app.NumberofTonesListBox = uilistbox(app.MultipleSineTonesTab);
            app.NumberofTonesListBox.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofTonesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofTonesListBoxValueChanged, true);
            app.NumberofTonesListBox.Value = '2';
            app.UITable = uitable(app.MultipleSineTonesTab);
            app.UITable.ColumnName = {'Amplitude'; 'Freq (Hz)'; 'Phase (deg)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalculateButton_multi = uibutton(app.MultipleSineTonesTab, 'push');
            app.CalculateButton_multi.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_multiPushed, true);
            app.CalculateButton_multi.Text = 'Calculate';
            app.CosineTab = uitab(app.TabGroup_2);
            app.CosineTab.Title = 'Cosine';
            app.CalculateButton_cosine = uibutton(app.CosineTab, 'push');
            app.CalculateButton_cosine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_cosinePushed, true);
            app.CalculateButton_cosine.Text = 'Calculate';
            app.AmplitudeEditField_2Label = uilabel(app.CosineTab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.CosineTab, 'text');
            app.AmplitudeEditField_2.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanging, true);
            app.FrequencyHzEditField_2Label = uilabel(app.CosineTab);
            app.FrequencyHzEditField_2Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_2 = uieditfield(app.CosineTab, 'text');
            app.FrequencyHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditField_2ValueChanging, true);
            app.DampedSineTab = uitab(app.TabGroup_2);
            app.DampedSineTab.Title = 'Damped Sine';
            app.FrequencyHzEditField_3Label = uilabel(app.DampedSineTab);
            app.FrequencyHzEditField_3Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_dsine = uieditfield(app.DampedSineTab, 'text');
            app.FrequencyHzEditField_dsine.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzEditField_dsineValueChanging, true);
            app.DampingRatio1EditFieldLabel = uilabel(app.DampedSineTab);
            app.DampingRatio1EditFieldLabel.Text = 'Damping Ratio < 1';
            app.DampingRatio1EditField = uieditfield(app.DampedSineTab, 'text');
            app.DampingRatio1EditField.ValueChangingFcn = createCallbackFcn(app, @DampingRatio1EditFieldValueChanging, true);
            app.CalculateButton_damped_sine = uibutton(app.DampedSineTab, 'push');
            app.CalculateButton_damped_sine.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_damped_sinePushed, true);
            app.CalculateButton_damped_sine.Text = 'Calculate';
            app.DelayTimeEditFieldLabel = uilabel(app.DampedSineTab);
            app.DelayTimeEditFieldLabel.Text = 'Delay Time (sec)';
            app.DelayTimeEditField = uieditfield(app.DampedSineTab, 'text');
            app.DelayTimeEditField.ValueChangingFcn = createCallbackFcn(app, @DelayTimeEditFieldValueChanging, true);
            app.DelayTimeEditFieldLabel_2 = uilabel(app.DampedSineTab);
            app.DelayTimeEditFieldLabel_2.Text = 'Amplitude';
            app.AmplitudeEditField_dsine = uieditfield(app.DampedSineTab, 'text');
            app.AmplitudeEditField_dsine.ValueChangingFcn = createCallbackFcn(app, @AmplitudeEditField_dsineValueChanging, true);
            app.SaveButton = uibutton(app.aTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DurationsecEditFieldLabel = uilabel(app.aTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.aTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.aTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.aTab, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.YaxisLabelEditFieldLabel = uilabel(app.aTab);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.aTab, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.ResultsTextAreaLabel = uilabel(app.aTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.aTab);
            app.OutputNameEditFieldLabel = uilabel(app.aTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.aTab, 'text');
            app.OutputNameEditField.Value = 'sine';
            app.bTab = uitab(app.GenerateTabGroup);
            app.bTab.Title = 'b';
            app.TabGroup_3 = uitabgroup(app.bTab);
            app.SineSweepTab = uitab(app.TabGroup_3);
            app.SineSweepTab.Title = 'Sine Sweep';
            app.GenerateButton = uibutton(app.SineSweepTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SweepTypeListBoxLabel = uilabel(app.SineSweepTab);
            app.SweepTypeListBoxLabel.Text = 'Sweep Type';
            app.SweepTypeListBox = uilistbox(app.SineSweepTab);
            app.SweepTypeListBox.Items = {'linear', 'log'};
            app.SweepTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SweepTypeListBoxValueChanged, true);
            app.SweepTypeListBox.Value = 'linear';
            app.SweepDirectionListBoxLabel = uilabel(app.SineSweepTab);
            app.SweepDirectionListBoxLabel.Text = 'Sweep Direction';
            app.SweepDirectionListBox = uilistbox(app.SineSweepTab);
            app.SweepDirectionListBox.Items = {'up', 'down', 'up & down'};
            app.SweepDirectionListBox.ValueChangedFcn = createCallbackFcn(app, @SweepDirectionListBoxValueChanged, true);
            app.SweepDirectionListBox.Value = 'up';
            app.SampleRateHzEditFieldLabel_sweep = uilabel(app.SineSweepTab);
            app.SampleRateHzEditFieldLabel_sweep.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_sweep = uieditfield(app.SineSweepTab, 'numeric');
            app.SampleRateHzEditField_sweep.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditField_sweepValueChanged, true);
            app.DurationsecEditFieldLabel_sweep = uilabel(app.SineSweepTab);
            app.DurationsecEditFieldLabel_sweep.Text = 'Duration (sec)';
            app.DurationsecEditField_sweep = uieditfield(app.SineSweepTab, 'numeric');
            app.DurationsecEditField_sweep.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditField_sweepValueChanged, true);
            app.TimeOptionListBoxLabel = uilabel(app.SineSweepTab);
            app.TimeOptionListBoxLabel.Text = 'Time Option';
            app.TimeOptionListBox = uilistbox(app.SineSweepTab);
            app.TimeOptionListBox.Items = {'Duration', 'Sweep Rate'};
            app.TimeOptionListBox.ValueChangedFcn = createCallbackFcn(app, @TimeOptionListBoxValueChanged, true);
            app.TimeOptionListBox.Value = 'Duration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SineSweepTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SineSweepTab, 'text');
            app.OutputArrayNameEditField.Value = 'sine_sweep';
            app.NumberofCoordinatesListBoxLabel = uilabel(app.SineSweepTab);
            app.NumberofCoordinatesListBoxLabel.Text = {'Number of '; 'Coordinates'};
            app.NumberofCoordinatesListBox = uilistbox(app.SineSweepTab);
            app.NumberofCoordinatesListBox.Items = {'2', '3', '4', '5', '6'};
            app.NumberofCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCoordinatesListBoxValueChanged, true);
            app.NumberofCoordinatesListBox.Value = '2';
            app.UITable_sweep = uitable(app.SineSweepTab);
            app.UITable_sweep.ColumnName = {'Freq (Hz)'; 'Accel (G)'};
            app.UITable_sweep.RowName = {};
            app.UITable_sweep.ColumnEditable = true;
            app.CalculateSpectrumFunctionListBoxLabel = uilabel(app.SineSweepTab);
            app.CalculateSpectrumFunctionListBoxLabel.Text = {'Calculate Spectrum '; 'Function'};
            app.CalculateSpectrumFunctionListBox = uilistbox(app.SineSweepTab);
            app.CalculateSpectrumFunctionListBox.Items = {'Yes', 'No'};
            app.CalculateSpectrumFunctionListBox.Value = 'No';
            app.NumberofOctavesEditFieldLabel = uilabel(app.SineSweepTab);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.SineSweepTab, 'numeric');
            app.NumberofOctavesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofOctavesEditFieldValueChanged, true);
            app.SDOFResponseTab = uitab(app.TabGroup_3);
            app.SDOFResponseTab.Title = 'SDOF Response';
            app.CalculateButton = uibutton(app.SDOFResponseTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsTextAreaLabel_sweep = uilabel(app.SDOFResponseTab);
            app.ResultsTextAreaLabel_sweep.Text = 'Results';
            app.ResultsTextArea_sweep = uitextarea(app.SDOFResponseTab);
            app.Image = uiimage(app.SDOFResponseTab);
            app.Image.ImageSource = 'sdof_base.jpg';
            app.Label_2 = uilabel(app.SDOFResponseTab);
            app.Label_2.Text = 'The Base Excitation Array must have two columns:  time (sec) & accel (G)';
            app.BaseExcitationArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.BaseExcitationArrayEditFieldLabel.Text = 'Base Excitation Array';
            app.BaseExcitationArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.BaseExcitationArrayEditField.ValueChangingFcn = createCallbackFcn(app, @BaseExcitationArrayEditFieldValueChanging, true);
            app.AccelResponseArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.AccelResponseArrayEditFieldLabel.Text = 'Accel Response Array';
            app.AccelResponseArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.AccelResponseArrayEditField.Value = 'accel_response';
            app.RelDispResponseArrayEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.RelDispResponseArrayEditFieldLabel.Text = 'Rel Disp Response Array';
            app.RelDispResponseArrayEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.RelDispResponseArrayEditField.Value = 'rel_disp_response';
            app.CalculateListBoxLabel = uilabel(app.SDOFResponseTab);
            app.CalculateListBoxLabel.Text = 'Calculate';
            app.CalculateListBox = uilistbox(app.SDOFResponseTab);
            app.CalculateListBox.Items = {'Time Histories Only', 'Transmissibility', 'Transmissibility & Phase'};
            app.CalculateListBox.Value = 'Time Histories Only';
            app.Label_3 = uilabel(app.SDOFResponseTab);
            app.Label_3.Text = 'The numerical engine is the Smallwood ramp invariant digital recursive filtering relationship.';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.AmplificationFactorQEditFieldLabel = uilabel(app.SDOFResponseTab);
            app.AmplificationFactorQEditFieldLabel.Text = 'Amplification Factor Q';
            app.AmplificationFactorQEditField = uieditfield(app.SDOFResponseTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.cTab = uitab(app.GenerateTabGroup);
            app.cTab.Title = 'c';
            app.TabGroup6 = uitabgroup(app.cTab);
            app.WhiteBasicTab = uitab(app.TabGroup6);
            app.WhiteBasicTab.Title = 'White Basic';
            app.CalculateButton_white_noise_basic = uibutton(app.WhiteBasicTab, 'push');
            app.CalculateButton_white_noise_basic.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_white_noise_basicPushed, true);
            app.CalculateButton_white_noise_basic.Text = 'Calculate';
            app.StdDevEditFieldLabel = uilabel(app.WhiteBasicTab);
            app.StdDevEditFieldLabel.Text = 'Std Dev ';
            app.StdDevEditField = uieditfield(app.WhiteBasicTab, 'text');
            app.StdDevEditField.ValueChangingFcn = createCallbackFcn(app, @StdDevEditFieldValueChanging, true);
            app.DurationsecEditField_2Label = uilabel(app.WhiteBasicTab);
            app.DurationsecEditField_2Label.Text = 'Duration (sec)';
            app.DurationsecEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.DurationsecEditField_white.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_whiteValueChanging, true);
            app.SampleRateHzEditField_2Label = uilabel(app.WhiteBasicTab);
            app.SampleRateHzEditField_2Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.SampleRateHzEditField_white.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_whiteValueChanging, true);
            app.YaxisLabelEditField_2Label_2 = uilabel(app.WhiteBasicTab);
            app.YaxisLabelEditField_2Label_2.Text = 'Y-axis Label';
            app.YaxisLabelEditField_white_2 = uieditfield(app.WhiteBasicTab, 'text');
            app.YaxisLabelEditField_white_2.Value = 'Accel (G)';
            app.RandomNumberSeedListBoxLabel = uilabel(app.WhiteBasicTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.WhiteBasicTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.ValueChangedFcn = createCallbackFcn(app, @RandomNumberSeedListBoxValueChanged, true);
            app.RandomNumberSeedListBox.Value = '1';
            app.OutputNameEditField_2Label = uilabel(app.WhiteBasicTab);
            app.OutputNameEditField_2Label.Text = 'Output Name';
            app.OutputNameEditField_white = uieditfield(app.WhiteBasicTab, 'text');
            app.OutputNameEditField_white.Value = 'white';
            app.WhiteCorrectedTab = uitab(app.TabGroup6);
            app.WhiteCorrectedTab.Title = 'White Corrected';
            app.TabGroup8 = uitabgroup(app.WhiteCorrectedTab);
            app.CalculateTab = uitab(app.TabGroup8);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton_white_noise_corrected = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_white_noise_corrected.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_white_noise_correctedPushed, true);
            app.CalculateButton_white_noise_corrected.Text = 'Calculate';
            app.DurationTaperPercentEditFieldLabel_cor = uilabel(app.CalculateTab);
            app.DurationTaperPercentEditFieldLabel_cor.Text = {'Duration Taper '; 'Percent'};
            app.DurationTaperPercentEditField_cor = uieditfield(app.CalculateTab, 'text');
            app.DurationTaperPercentEditField_cor.ValueChangingFcn = createCallbackFcn(app, @DurationTaperPercentEditField_corValueChanging, true);
            app.DurationTaperPercentEditField_cor.Value = '1';
            app.SampleRateHzEditField_4Label = uilabel(app.CalculateTab);
            app.SampleRateHzEditField_4Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.SampleRateHzEditField_wc.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_wcValueChanging, true);
            app.DurationsecEditField_5Label = uilabel(app.CalculateTab);
            app.DurationsecEditField_5Label.Text = 'Duration (sec)';
            app.DurationsecEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.DurationsecEditField_wc.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_wcValueChanging, true);
            app.StdDevEditField_2Label = uilabel(app.CalculateTab);
            app.StdDevEditField_2Label.Text = 'Std Dev';
            app.StdDevEditField_wc = uieditfield(app.CalculateTab, 'text');
            app.StdDevEditField_wc.ValueChangingFcn = createCallbackFcn(app, @StdDevEditField_wcValueChanging, true);
            app.SelectUnitsListBoxLabel = uilabel(app.CalculateTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.CalculateTab);
            app.SelectUnitsListBox.Items = {'G, in/sec, in      ', 'G, cm/sec, mm      ', 'm/sec^2, cm/sec, mm'};
            app.SelectUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectUnitsListBoxValueChanged, true);
            app.SelectUnitsListBox.Value = 'G, in/sec, in      ';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor = uilabel(app.CalculateTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor.Text = {'Select Trend Removal '; 'Between Integrations'};
            app.T2_ListBox_cor = uilistbox(app.CalculateTab);
            app.T2_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T2_ListBox_cor.ValueChangedFcn = createCallbackFcn(app, @T2_ListBox_corValueChanged, true);
            app.T2_ListBox_cor.Value = 'None';
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2 = uilabel(app.CalculateTab);
            app.SelectTrendRemovalBetweenIntegrationsListBoxLabel_cor_2.Text = {'Select Trend Removal '; 'After Integrations'};
            app.T3_ListBox_cor = uilistbox(app.CalculateTab);
            app.T3_ListBox_cor.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.T3_ListBox_cor.ValueChangedFcn = createCallbackFcn(app, @T3_ListBox_corValueChanged, true);
            app.T3_ListBox_cor.Value = 'None';
            app.SaveTab = uitab(app.TabGroup8);
            app.SaveTab.Title = 'Save';
            app.SaveButton_cor = uibutton(app.SaveTab, 'push');
            app.SaveButton_cor.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_corPushed, true);
            app.SaveButton_cor.Text = 'Save';
            app.ExportFunctionListBoxLabel_cor = uilabel(app.SaveTab);
            app.ExportFunctionListBoxLabel_cor.Text = 'Export Function';
            app.ExportFunctionListBox_cor = uilistbox(app.SaveTab);
            app.ExportFunctionListBox_cor.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.ExportFunctionListBox_cor.Value = 'Acceleration';
            app.OutputNameEditFieldLabel_cor = uilabel(app.SaveTab);
            app.OutputNameEditFieldLabel_cor.Text = 'Output Name';
            app.OutputNameEditField_cor = uieditfield(app.SaveTab, 'text');
            app.TwoCorrelatedWhiteSignalsTab = uitab(app.TabGroup6);
            app.TwoCorrelatedWhiteSignalsTab.Title = 'Two Correlated White Signals';
            app.CalculateButton_two_correlated_white = uibutton(app.TwoCorrelatedWhiteSignalsTab, 'push');
            app.CalculateButton_two_correlated_white.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_two_correlated_whitePushed, true);
            app.CalculateButton_two_correlated_white.Text = 'Calculate';
            app.OutputNameEditField_2Label_2 = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.OutputNameEditField_2Label_2.Text = 'Output Array Prefix';
            app.OutputNameEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.OutputNameEditField_two_white.Value = 'signal';
            app.CorrelationCoefficientEditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.CorrelationCoefficientEditFieldLabel.Text = 'Correlation Coefficient';
            app.CorrelationCoefficientEditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.CorrelationCoefficientEditField.ValueChangingFcn = createCallbackFcn(app, @CorrelationCoefficientEditFieldValueChanging, true);
            app.CorrelationCoefficientEditField.Value = '0';
            app.DurationsecEditField_5Label_2 = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.DurationsecEditField_5Label_2.Text = 'Duration (sec)';
            app.DurationsecEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.DurationsecEditField_two_white.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_two_whiteValueChanging, true);
            app.SampleRateHzEditField_2Label_2 = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.SampleRateHzEditField_2Label_2.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_two_white = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.SampleRateHzEditField_two_white.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditField_two_whiteValueChanging, true);
            app.StdDev1EditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.StdDev1EditFieldLabel.Text = 'Std Dev 1';
            app.StdDev1EditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.StdDev1EditField.ValueChangingFcn = createCallbackFcn(app, @StdDev1EditFieldValueChanging, true);
            app.StdDev2EditFieldLabel = uilabel(app.TwoCorrelatedWhiteSignalsTab);
            app.StdDev2EditFieldLabel.Text = 'Std Dev 2';
            app.StdDev2EditField = uieditfield(app.TwoCorrelatedWhiteSignalsTab, 'text');
            app.StdDev2EditField.ValueChangingFcn = createCallbackFcn(app, @StdDev2EditFieldValueChanging, true);
            app.AddWhitetoSignalTab = uitab(app.TabGroup6);
            app.AddWhitetoSignalTab.Title = 'Add White to Signal';
            app.CalculateButton_add_white_noise = uibutton(app.AddWhitetoSignalTab, 'push');
            app.CalculateButton_add_white_noise.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_add_white_noisePushed, true);
            app.CalculateButton_add_white_noise.Text = 'Calculate';
            app.OutputNameEditField_2Label_3 = uilabel(app.AddWhitetoSignalTab);
            app.OutputNameEditField_2Label_3.Text = 'Output Name';
            app.OutputNameEditField_add_white = uieditfield(app.AddWhitetoSignalTab, 'text');
            app.OutputNameEditField_add_white.Value = 'composite';
            app.StdDevEditField_2Label_2 = uilabel(app.AddWhitetoSignalTab);
            app.StdDevEditField_2Label_2.Text = 'Std Dev ';
            app.StdDevEditField_add_white = uieditfield(app.AddWhitetoSignalTab, 'text');
            app.StdDevEditField_add_white.ValueChangingFcn = createCallbackFcn(app, @StdDevEditField_add_whiteValueChanging, true);
            app.text1 = uilabel(app.AddWhitetoSignalTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.AddWhitetoSignalTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.UpdateListButton_2 = uibutton(app.AddWhitetoSignalTab, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.SelectInputArrayListBoxLabel_2 = uilabel(app.AddWhitetoSignalTab);
            app.SelectInputArrayListBoxLabel_2.Text = 'Select Input Array';
            app.SelectInputArrayListBox_add_white = uilistbox(app.AddWhitetoSignalTab);
            app.SelectInputArrayListBox_add_white.Items = {'Item 1'};
            app.ResultsTextAreaLabel_white = uilabel(app.cTab);
            app.ResultsTextAreaLabel_white.Text = 'Results';
            app.ResultsTextArea_white = uitextarea(app.cTab);
            app.BandlimitviaFilteringListBoxLabel = uilabel(app.cTab);
            app.BandlimitviaFilteringListBoxLabel.Text = 'Band-limit via Filtering?';
            app.BandlimitviaFilteringListBox = uilistbox(app.cTab);
            app.BandlimitviaFilteringListBox.Items = {'yes, low-pass', 'yes, band-pass', 'none'};
            app.BandlimitviaFilteringListBox.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaFilteringListBoxValueChanged, true);
            app.BandlimitviaFilteringListBox.Value = 'none';
            app.LowpassFreqHzEditFieldLabel = uilabel(app.cTab);
            app.LowpassFreqHzEditFieldLabel.Text = 'Low-pass Freq (Hz)';
            app.LowpassFreqHzEditField = uieditfield(app.cTab, 'text');
            app.LowpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowpassFreqHzEditFieldValueChanging, true);
            app.HighpassFreqHzEditFieldLabel = uilabel(app.cTab);
            app.HighpassFreqHzEditFieldLabel.Text = 'High-pass Freq (Hz)';
            app.HighpassFreqHzEditField = uieditfield(app.cTab, 'text');
            app.HighpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @HighpassFreqHzEditFieldValueChanging, true);
            app.YaxisLabelEditField_2Label = uilabel(app.cTab);
            app.YaxisLabelEditField_2Label.Text = 'Y-axis Label';
            app.YaxisLabelEditField_white = uieditfield(app.cTab, 'text');
            app.YaxisLabelEditField_white.Value = 'Accel (G)';
            app.dTab = uitab(app.GenerateTabGroup);
            app.dTab.Title = 'd';
            app.TabGroup7 = uitabgroup(app.dTab);
            app.RandomwithSkewnessKurtosisTab = uitab(app.TabGroup7);
            app.RandomwithSkewnessKurtosisTab.Title = 'Random with Skewness & Kurtosis';
            app.CalculateButton_sk = uibutton(app.RandomwithSkewnessKurtosisTab, 'push');
            app.CalculateButton_sk.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_skPushed, true);
            app.CalculateButton_sk.Text = 'Calculate';
            app.OutputNameEditField_2Label_4 = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.OutputNameEditField_2Label_4.Text = 'Output Name';
            app.OutputNameEditField_sk = uieditfield(app.RandomwithSkewnessKurtosisTab, 'text');
            app.OutputNameEditField_sk.ValueChangedFcn = createCallbackFcn(app, @OutputNameEditField_skValueChanged, true);
            app.OutputNameEditField_sk.Value = 'random';
            app.Filteratsamplerate10Label = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.Filteratsamplerate10Label.Text = 'Filter at (sample rate ) / 10';
            app.BandlimitviaLowpassFilterListBoxLabel = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.BandlimitviaLowpassFilterListBoxLabel.Text = {'Band-limit via '; 'Lowpass Filter'};
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Items = {'yes', 'no'};
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaLowpassFilterListBox_Sarkani_skValueChanged, true);
            app.BandlimitviaLowpassFilterListBox_Sarkani_sk.Value = 'yes';
            app.KurtosisEditFieldLabel = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.RandomwithSkewnessKurtosisTab, 'text');
            app.KurtosisEditField.ValueChangingFcn = createCallbackFcn(app, @KurtosisEditFieldValueChanging, true);
            app.RandomNumberSeedListBox_2Label = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.RandomNumberSeedListBox_2Label.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox_2 = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.RandomNumberSeedListBox_2.Items = {'1', 'None'};
            app.RandomNumberSeedListBox_2.ValueChangedFcn = createCallbackFcn(app, @RandomNumberSeedListBox_2ValueChanged, true);
            app.RandomNumberSeedListBox_2.Value = '1';
            app.SkewnessListBoxLabel = uilabel(app.RandomwithSkewnessKurtosisTab);
            app.SkewnessListBoxLabel.Text = 'Skewness';
            app.SkewnessListBox = uilistbox(app.RandomwithSkewnessKurtosisTab);
            app.SkewnessListBox.Items = {'0', '0.1', '0.2', '0.3', '0.4', '0.5'};
            app.SkewnessListBox.ValueChangedFcn = createCallbackFcn(app, @SkewnessListBoxValueChanged, true);
            app.SkewnessListBox.Value = '0';
            app.SarkaniRandomTab = uitab(app.TabGroup7);
            app.SarkaniRandomTab.Title = 'Sarkani Random';
            app.CalculateButton_Sarkani = uibutton(app.SarkaniRandomTab, 'push');
            app.CalculateButton_Sarkani.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SarkaniPushed, true);
            app.CalculateButton_Sarkani.Text = 'Calculate';
            app.OutputNameEditField_2Label_5 = uilabel(app.SarkaniRandomTab);
            app.OutputNameEditField_2Label_5.Text = 'Output Name';
            app.OutputNameEditField_Sarkani = uieditfield(app.SarkaniRandomTab, 'text');
            app.OutputNameEditField_Sarkani.Value = 'random';
            app.Filteratsamplerate10Label_2 = uilabel(app.SarkaniRandomTab);
            app.Filteratsamplerate10Label_2.Text = 'Filter at (sample rate ) / 10';
            app.SpecificationMethodListBoxLabel = uilabel(app.SarkaniRandomTab);
            app.SpecificationMethodListBoxLabel.Text = {'Specification '; 'Method'};
            app.SpecificationMethodListBox = uilistbox(app.SarkaniRandomTab);
            app.SpecificationMethodListBox.Items = {'kurtosis', 'beta & n'};
            app.SpecificationMethodListBox.ValueChangedFcn = createCallbackFcn(app, @SpecificationMethodListBoxValueChanged, true);
            app.SpecificationMethodListBox.Value = 'kurtosis';
            app.betaEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.betaEditField.ValueChangingFcn = createCallbackFcn(app, @betaEditFieldValueChanging, true);
            app.nEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.nEditField.ValueChangingFcn = createCallbackFcn(app, @nEditFieldValueChanging, true);
            app.kurtosisEditFieldLabel = uilabel(app.SarkaniRandomTab);
            app.kurtosisEditFieldLabel.Text = 'kurtosis';
            app.kurtosisEditField = uieditfield(app.SarkaniRandomTab, 'text');
            app.kurtosisEditField.ValueChangingFcn = createCallbackFcn(app, @kurtosisEditFieldValueChanging, true);
            app.BandlimitviaLowpassFilterListBoxLabel_2 = uilabel(app.SarkaniRandomTab);
            app.BandlimitviaLowpassFilterListBoxLabel_2.Text = {'Band-limit via '; 'Lowpass Filter'};
            app.BandlimitviaLowpassFilterListBox_Sarkani = uilistbox(app.SarkaniRandomTab);
            app.BandlimitviaLowpassFilterListBox_Sarkani.Items = {'yes', 'no'};
            app.BandlimitviaLowpassFilterListBox_Sarkani.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaLowpassFilterListBox_SarkaniValueChanged, true);
            app.BandlimitviaLowpassFilterListBox_Sarkani.Value = 'yes';
            app.PinkNoiseTab = uitab(app.TabGroup7);
            app.PinkNoiseTab.Title = 'Pink Noise';
            app.CalculateButton_pink = uibutton(app.PinkNoiseTab, 'push');
            app.CalculateButton_pink.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_pinkPushed, true);
            app.CalculateButton_pink.Text = 'Calculate';
            app.OutputNameEditField_2Label_6 = uilabel(app.PinkNoiseTab);
            app.OutputNameEditField_2Label_6.Text = 'Output Name';
            app.OutputNameEditField_pink = uieditfield(app.PinkNoiseTab, 'text');
            app.OutputNameEditField_pink.Value = 'pink';
            app.PinknoiseovertheaudiofrequencydomainLabel = uilabel(app.PinkNoiseTab);
            app.PinknoiseovertheaudiofrequencydomainLabel.Text = 'Pink noise over the audio frequency domain';
            app.ResultsTextAreaLabel_random_sk = uilabel(app.dTab);
            app.ResultsTextAreaLabel_random_sk.Text = 'Results';
            app.ResultsTextArea_random_sk = uitextarea(app.dTab);
            app.DurationsecEditField_2Label_2 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_2.Text = 'Duration (sec)';
            app.DurationsecEditField_sk = uieditfield(app.dTab, 'text');
            app.DurationsecEditField_sk.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditField_skValueChanging, true);
            app.DurationsecEditField_2Label_3 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_3.Text = 'Std Dev';
            app.StdDevEditField_sk = uieditfield(app.dTab, 'text');
            app.StdDevEditField_sk.ValueChangingFcn = createCallbackFcn(app, @StdDevEditField_skValueChanging, true);
            app.YaxisLabelEditField_2Label_3 = uilabel(app.dTab);
            app.YaxisLabelEditField_2Label_3.Text = 'Y-axis Label';
            app.YaxisLabelEditField_sk = uieditfield(app.dTab, 'text');
            app.YaxisLabelEditField_sk.Value = 'Accel (G)';
            app.DurationsecEditField_2Label_4 = uilabel(app.dTab);
            app.DurationsecEditField_2Label_4.Text = 'Sample Rate (Hz)';
            app.SampleRateEditField_sk = uieditfield(app.dTab, 'text');
            app.SampleRateEditField_sk.ValueChangingFcn = createCallbackFcn(app, @SampleRateEditField_skValueChanging, true);
            app.FatigueTab = uitab(app.TabGroup);
            app.FatigueTab.Title = 'Fatigue';
            app.FatigueListBoxLabel = uilabel(app.FatigueTab);
            app.FatigueListBoxLabel.Text = 'Fatigue List Box';
            app.FatigueListBox = uilistbox(app.FatigueTab);
            app.FatigueListBox.Items = {'Rainflow Cycle Counting', 'Fatigue Damage Spectrum (FDS)', 'Steinberg'};
            app.FatigueListBox.Value = 'Rainflow Cycle Counting';
            app.AnalyzeButton_3 = uibutton(app.FatigueTab, 'push');
            app.AnalyzeButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_3Pushed, true);
            app.AnalyzeButton_3.Text = 'Analyze';
            app.AcousticsTab = uitab(app.TabGroup);
            app.AcousticsTab.Title = 'Acoustics';
            app.AnalysisListBoxLabel = uilabel(app.AcousticsTab);
            app.AnalysisListBoxLabel.Text = 'Analysis';
            app.AnalysisListBox = uilistbox(app.AcousticsTab);
            app.AnalysisListBox.Items = {'Cylindrical Shell Ring Frequency', 'Acoustic to Seismic Coupling', 'Doppler Shift', 'Sound Pressure Intensity', 'Launch Vehicle Aerodynamic Flow, Wilby'};
            app.AnalysisListBox.Value = 'Cylindrical Shell Ring Frequency';
            app.AnalyzeButton_4 = uibutton(app.AcousticsTab, 'push');
            app.AnalyzeButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_4Pushed, true);
            app.AnalyzeButton_4.Text = 'Analyze';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.SelectPlotTypeListBoxLabel = uilabel(app.PlottingTab);
            app.SelectPlotTypeListBoxLabel.Text = 'Select Plot Type';
            app.SelectPlotTypeListBox = uilistbox(app.PlottingTab);
            app.SelectPlotTypeListBox.Items = {'Time History', 'PSD', 'SPL', 'Multiple Curves'};
            app.SelectPlotTypeListBox.Value = 'Time History';
            app.PlotButton = uibutton(app.PlottingTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.SelectAnalysisListBox_2Label = uilabel(app.UtilitiesTab);
            app.SelectAnalysisListBox_2Label.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Utilities = uilistbox(app.UtilitiesTab);
            app.SelectAnalysisListBox_Utilities.Items = {'Unit Conversion', 'Sine & SRS Amplitude Conversion', 'SDOF Response:  Sine, Peak Random & Miles', 'Statistical Distributions', 'Sine Sweep Parameters', 'Sound File Editor', 'dB Calculations', 'SMC-S-016 Enveloping for PSD & SPL MPE', 'Strouhal Number'};
            app.SelectAnalysisListBox_Utilities.ValueChangedFcn = createCallbackFcn(app, @SelectAnalysisListBox_UtilitiesValueChanged, true);
            app.SelectAnalysisListBox_Utilities.Value = 'Unit Conversion';
            app.AnalyzeButton_Utilities = uibutton(app.UtilitiesTab, 'push');
            app.AnalyzeButton_Utilities.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_UtilitiesPushed, true);
            app.AnalyzeButton_Utilities.Text = 'Analyze';
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.ImportTab.ButtonDownFcn = createCallbackFcn(app, @ImportTabButtonDown, true);
            app.TabGroup2 = uitabgroup(app.ImportTab);
            app.LibraryDataTab = uitab(app.TabGroup2);
            app.LibraryDataTab.Title = 'Library Data';
            app.LibraryDataTab.ButtonDownFcn = createCallbackFcn(app, @LibraryDataTabButtonDown, true);
            app.SelectTypeListBoxLabel = uilabel(app.LibraryDataTab);
            app.SelectTypeListBoxLabel.Text = 'Select Type';
            app.SelectTypeListBox = uilistbox(app.LibraryDataTab);
            app.SelectTypeListBox.Items = {'Acceleration Time History', 'Acoustic Time History    ', 'Earthquake Time History', 'Stress Time History', 'PSD', 'Sound Pressure Level (SPL)', 'SRS', 'Wavelet Table', 'Sound Files', 'Sound Power Level'};
            app.SelectTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectTypeListBoxValueChanged, true);
            app.SelectTypeListBox.Value = 'Acceleration Time History';
            app.SelectArrayListBoxLabel = uilabel(app.LibraryDataTab);
            app.SelectArrayListBoxLabel.Text = 'Select Array';
            app.SelectArrayListBox = uilistbox(app.LibraryDataTab);
            app.ReadDataButton = uibutton(app.LibraryDataTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.TextorExcelTab = uitab(app.TabGroup2);
            app.TextorExcelTab.Title = 'Text or Excel';
            app.ReadFileButton = uibutton(app.TextorExcelTab, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.Label = uilabel(app.TextorExcelTab);
            app.Label.Text = {'A = readmatrix(filename) creates an array by reading column-oriented '; 'data from a file. The readmatrix function performs automatic detection '; 'of import parameters for your file.'; ''; 'readmatrix determines the file format from the file extension:'; ''; '.txt, .dat, or .csv for delimited text files'; ''; '.xls, .xlsb, .xlsm, .xlsx, .xltm, .xltx, or .ods for spreadsheet files'; ''; 'For files containing mixed numeric and text data, readmatrix imports '; 'the data as a numeric array by default.   '; ''; 'If the file contains more than two columns, then the first column is '; 'assumed to be either time or frequency, with the following columns '; 'as channels.  Individual channel arrays will be extracted accordingly. '};
            app.matTab = uitab(app.TabGroup2);
            app.matTab.Title = 'mat';
            app.ReadmatButton = uibutton(app.matTab, 'push');
            app.ReadmatButton.ButtonPushedFcn = createCallbackFcn(app, @ReadmatButtonPushed, true);
            app.ReadmatButton.Text = 'Read .mat';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.pushbutton_return = uibutton(app.ExportTab, 'push');
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel1 = uipanel(app.ExportTab);
            app.uipanel1.Title = 'Existing Matlab Workspace Array Name';
            app.uipanel1.Tag = 'uipanel1';
            app.UpdateListButton = uibutton(app.uipanel1, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.uipanel1);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.uipanel1);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.PerformExportButton = uibutton(app.uipanel1, 'push');
            app.PerformExportButton.ButtonPushedFcn = createCallbackFcn(app, @PerformExportButtonPushed, true);
            app.PerformExportButton.Text = 'Perform Export';
            app.uipanel2 = uipanel(app.ExportTab);
            app.uipanel2.Title = 'Select Export Format';
            app.uipanel2.Tag = 'uipanel2';
            app.listbox_format = uilistbox(app.uipanel2);
            app.listbox_format.Items = {'ASCII Text', 'Excel               ', 'mat'};
            app.listbox_format.Tag = 'listbox_format';
            app.listbox_format.Value = 'Excel               ';
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            app.UpdateButton = uibutton(app.WorkspaceTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ClearButton = uibutton(app.WorkspaceTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.WorkspaceListBoxLabel = uilabel(app.WorkspaceTab);
            app.WorkspaceListBoxLabel.Text = 'Workspace';
            app.WorkspaceListBox = uilistbox(app.WorkspaceTab);
            app.WorkspaceListBox.Items = {};
            app.WorkspaceListBox.Value = {};
            app.CheckforVersionUpdateButton = uibutton(app.vibrationdataaltUIFigure, 'push');
            app.CheckforVersionUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforVersionUpdateButtonPushed, true);
            app.CheckforVersionUpdateButton.Text = 'Check for Version Update';
            app.CalculateFFTButton = uibutton(app.vibrationdataaltUIFigure, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.SDOFResponsetoBaseInputButton = uibutton(app.vibrationdataaltUIFigure, 'push');
            app.SDOFResponsetoBaseInputButton.ButtonPushedFcn = createCallbackFcn(app, @SDOFResponsetoBaseInputButtonPushed, true);
            app.SDOFResponsetoBaseInputButton.Text = {'SDOF Response '; 'to Base Input'};
            % Show the figure after all components are created
            app.vibrationdataaltUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_alt

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.vibrationdataaltUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.vibrationdataaltUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vibrationdataaltUIFigure)
        end
    end
end