classdef vibrationdata_bandsplit_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSDBandsplitUIFigure            matlab.ui.Figure
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        OutputArrayNamePrefixEditField  matlab.ui.control.EditField
        OutputArrayNamePrefixEditFieldLabel  matlab.ui.control.Label
        StrategyListBox                 matlab.ui.control.ListBox
        StrategyListBoxLabel            matlab.ui.control.Label
        NumberofBandsListBox            matlab.ui.control.ListBox
        NumberofBandsListBoxLabel       matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        ThisprogramperformsbandsplittingonaPSDtestspecificationLabel  matlab.ui.control.Label
        vibrationdata_bandsplit_amver11byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        method % Description
        THM    %
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end

        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
            try
                app.SelectInputArrayListBox.Value=app.SelectInputArrayListBox.Items{1};
            catch
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ClosePlots(app);

            disp(' ');
            disp(' * * * * * ');
            disp(' ');

            if(app.method==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            A=app.THM;

            fig_num=1;
            
            fmin=min(A(:,1));
            fmax=max(A(:,1));
            
            xlab='Frequency (Hz)';
            ylab='Accel (G^2/Hz)';
            
            [~,input_rms] = calculate_PSD_slopes_no(A(:,1),A(:,2));
            
            t_string=sprintf('Reference PSD  %6.3g GRMS',input_rms);    
            [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,A,fmin,fmax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            nb=str2num(app.NumberofBandsListBox.Value);
            
            f=A(:,1);
            a=A(:,2);
            
            tpi=2*pi;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  intlog method for numerical stability
            %
            df=0.5;
            %
            xi=min(f):df:max(f); 
            %
            xL=log10(f);
            yL=log10(a);
            xiL=log10(xi);
            %
            yiL = interp1(xL,yL,xiL);
            %
            ni=length(xi);
            %
            fi=zeros(ni,1);
            ai=zeros(ni,1);
            %
            for i=1:ni
                fi(i)=10^xiL(i);
                ai(i)=10^yiL(i);
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            n=length(f);
            %

            Items = strtrim(app.StrategyListBox.Items);
            im = strtrim(app.StrategyListBox.Value);
            nstrategy = find(strcmp(Items, im));
            
            if(nstrategy==1)
                [bpsd,numb]=bandsplit_cases(f,a,fi,ai,n,ni,df,nb);
            end
            if(nstrategy==2)
                [bpsd,numb]=bandsplit_cases_velox(f,a,fi,ai,n,ni,df,nb);  
            end
            if(nstrategy==3)
                [bpsd,numb]=bandsplit_cases_frequency(f,a,fi,ai,n,ni,df,nb);  
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            for j=1:nb
            %
                clear ff;
                clear aa;
            %
                mm=numb(j);
                ff=bpsd(j,1:mm,1);
                aa=bpsd(j,1:mm,2);
            %
                ff=fix_size(ff);
                aa=fix_size(aa);
            %
                [~,grms]=calculate_PSD_slopes_no(ff,aa);
                
                nn=length(ff);
                
                vv=zeros(nn,1);
                
                for ij=1:nn
                    vv(ij)=386^2*aa(ij)/(tpi*ff(ij))^2;
                end
                
                [~,vrms]=calculate_PSD_slopes_no(ff,vv);    
                
                disp(' ');
                out1=sprintf(' PSD %d  %7.3g GRMS, %7.3g (in/sec) RMS',j,grms,vrms);
                disp(out1);
                disp(' ');
                disp(' Freq(Hz)  Accel(G^2/Hz) ');
            %
                for i=1:mm
                    out1=sprintf(' %8.4g  %8.4g ',ff(i),aa(i));
                    disp(out1);
                end
            %
                varname1=sprintf('band%d',j);
                eval([varname1 ' =[ff aa];']);
            %
            
                AB=[ff aa]; 
            
                t_string=sprintf('Band %d PSD  %6.3g GRMS',j,grms);    
                [fig_num]=plot_PSD_function(fig_num,xlab,ylab,t_string,AB,fmin,fmax);
            %
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            name=app.OutputArrayNamePrefixEditField.Value;

            output_name1=sprintf('%s_1',name);
            output_name2=sprintf('%s_2',name); 
            output_name3=sprintf('%s_3',name);
            output_name4=sprintf('%s_4',name); 

            assignin('base', output_name1, band1);
            assignin('base', output_name2, band2);
            
            if(nb>=3)
                assignin('base', output_name3, band3);
            end
            if(nb==4)
                assignin('base', output_name4, band4);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            if(nb==4)
                z=[band1; band2; band3; band4];
                zz=[ band1(1,:); band1(end,:); band2(end,:); band3(end,:); band4(end,:)];
                t_stringz=sprintf('PSD with Bandsplit Markers  %6.3g GRMS',input_rms);    
                [fig_num]=plot_PSD_function_markers(fig_num,xlab,ylab,t_stringz,z,zz,fmin,fmax);    
            end

            sss=sprintf(' %s arrays saved to Workspace',name);
            msgbox(sss);

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
           update(app); 
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSDBandsplitUIFigure = uifigure('Visible', 'off');
            app.PSDBandsplitUIFigure.Name = 'PSD Bandsplit';
            app.vibrationdata_bandsplit_amver11byTomIrvineLabel = uilabel(app.PSDBandsplitUIFigure);
            app.vibrationdata_bandsplit_amver11byTomIrvineLabel.Text = 'vibrationdata_bandsplit_a.m    ver 1.1  by Tom Irvine';
            app.ThisprogramperformsbandsplittingonaPSDtestspecificationLabel = uilabel(app.PSDBandsplitUIFigure);
            app.ThisprogramperformsbandsplittingonaPSDtestspecificationLabel.Text = 'This program performs bandsplitting on a PSD test specification.  The input array must have two columns: freq (Hz) & accel (G^2/Hz)';
            app.CalculateButton = uibutton(app.PSDBandsplitUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReturnButton = uibutton(app.PSDBandsplitUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.NumberofBandsListBoxLabel = uilabel(app.PSDBandsplitUIFigure);
            app.NumberofBandsListBoxLabel.Text = 'Number of Bands';
            app.NumberofBandsListBox = uilistbox(app.PSDBandsplitUIFigure);
            app.NumberofBandsListBox.Items = {'2', '3', '4'};
            app.NumberofBandsListBox.Value = '2';
            app.StrategyListBoxLabel = uilabel(app.PSDBandsplitUIFigure);
            app.StrategyListBoxLabel.Text = 'Strategy';
            app.StrategyListBox = uilistbox(app.PSDBandsplitUIFigure);
            app.StrategyListBox.Items = {'Equal Acceleration', 'Equal Velocity', 'Equal Frequency Band'};
            app.StrategyListBox.Value = 'Equal Acceleration';
            app.OutputArrayNamePrefixEditFieldLabel = uilabel(app.PSDBandsplitUIFigure);
            app.OutputArrayNamePrefixEditFieldLabel.Text = 'Output Array Name Prefix';
            app.OutputArrayNamePrefixEditField = uieditfield(app.PSDBandsplitUIFigure, 'text');
            app.OutputArrayNamePrefixEditField.Value = 'split_psd';
            app.listbox_method = uilistbox(app.PSDBandsplitUIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.PSDBandsplitUIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.PSDBandsplitUIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.PSDBandsplitUIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.PSDBandsplitUIFigure);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            % Show the figure after all components are created
            app.PSDBandsplitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_bandsplit_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PSDBandsplitUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PSDBandsplitUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSDBandsplitUIFigure)
        end
    end
end