classdef vibrationdata_doppler_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        uipanel2                 matlab.ui.container.Panel
        text_results_text        matlab.ui.control.Label
        edit_results             matlab.ui.control.EditField
        uipanel1                 matlab.ui.container.Panel
        edit_source_velocity     matlab.ui.control.EditField
        text_receiver_velocity   matlab.ui.control.Label
        text_source_velocity     matlab.ui.control.Label
        text_apparent_frequency  matlab.ui.control.Label
        text_source_frequency    matlab.ui.control.Label
        edit_receiver_velocity   matlab.ui.control.EditField
        edit_apparent_frequency  matlab.ui.control.EditField
        edit_source_frequency    matlab.ui.control.EditField
        text4                    matlab.ui.control.Label
        listbox_type             matlab.ui.control.ListBox
        edit_speed               matlab.ui.control.EditField
        text_speed_sound         matlab.ui.control.Label
        listbox_units            matlab.ui.control.ListBox
        text2                    matlab.ui.control.Label
        pushbutton_return        matlab.ui.control.Button
        pushbutton_calculate     matlab.ui.control.Button
        text1                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        n_unit %
        n_analysis %
    end
    
    
    methods (Access = private)
        function clear_results(app, hObject, eventdata, handles)
            app.edit_results.Visible='off';
            app.text_results_text.Visible='off';
        end
        
        function select_analysis(app, hObject, eventdata, handles)
            %
            app.text_source_frequency.Visible='on';
            app.text_apparent_frequency.Visible='on';
            app.text_source_velocity.Visible='on';
            app.text_receiver_velocity.Visible='on';
            app.edit_source_frequency.Visible='on';
            app.edit_apparent_frequency.Visible='on';
            app.edit_source_velocity.Visible='on';
            app.edit_receiver_velocity.Visible='on';
            %
            

            Items = char(app.listbox_type.Items);

            im=app.listbox_type.Value;

            [app.n_analysis]=find_index(Items,im);
            
            if(app.n_analysis==1)  % apparent frequency
                app.text_apparent_frequency.Visible='off';
                app.edit_apparent_frequency.Visible='off';
            end
            if(app.n_analysis==2)  % source frequency
                app.text_source_frequency.Visible='off';
                app.edit_source_frequency.Visible='off';
            end
            if(app.n_analysis==3)  % source velocity
                app.text_source_velocity.Visible='off';
                app.edit_source_velocity.Visible='off';
            end
            if(app.n_analysis==4)  % receiver velocity
                app.text_receiver_velocity.Visible='off';
                app.edit_receiver_velocity.Visible='off';
            end


            Items = char(app.listbox_units.Items)

            im=app.listbox_units.Value;

            [app.n_unit]=find_index(Items,im);
            
            if(app.n_unit==1)
                app.text_speed_sound.Text='Speed of Sound (ft/sec)';
                app.edit_speed.Value='1120';
                app.text_receiver_velocity.Text='Receiver Velocity (ft/sec)';
                app.text_source_velocity.Text='Source Velocity (ft/sec)';
            end
            if(app.n_unit==2)
                app.text_speed_sound.Text='Speed of Sound (mph)';
                app.edit_speed.Value='767';
                app.text_receiver_velocity.Text='Receiver Velocity (mph)';
                app.text_source_velocity.Text='Source Velocity (mph)';
            end
            if(app.n_unit==3)
                app.text_speed_sound.Text='Speed of Sound (m/sec)';
                app.edit_speed.Value='343';
                app.text_receiver_velocity.Text='Receiver Velocity (m/sec)';
                app.text_source_velocity.Text='Source Velocity (m/sec)';
            end
            if(app.n_unit==4)
                app.text_speed_sound.Text='Speed of Sound (km/hr)';
                app.edit_speed.Value='1234';
                app.text_receiver_velocity.Text='Receiver Velocity (km/hr)';
                app.text_source_velocity.Text='Source Velocity (km/hr)';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_doppler_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_doppler (see VARARGIN)
            
            % Choose default command line output for vibrationdata_doppler
            handles.output = hObject;
            
            clear_results(app);

            select_analysis(app);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_type
        function listbox_type_Callback(app, event)

            clear_results(app);
            select_analysis(app);
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            clear_results(app);
            select_analysis(app);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            c=str2num(app.edit_speed.Value);
            
            clear fs;
            clear fa;
            clear u;
            clear v;
            
            if(app.n_analysis==1)  % apparent frequency
            %
                fs=str2num(app.edit_source_frequency.Value);
                 u=str2num(app.edit_source_velocity.Value);
                 v=str2num(app.edit_receiver_velocity.Value);
            %
                app.text_results_text.Text='Apparent Frequency (Hz)';
            %
                    if(u>=c)
                        warndlg('Source velocity must be < speed of sound.','Warning');
                    end
                    if(v>=c)
                        warndlg('Receiver velocity must be < speed of sound.','Warning');
                    end
                    if(u<c && v<c)
                        clear fa;
            			fa=fs*((c-v)/(c-u));
                        out1=sprintf('%8.4g',fa);
                        app.text_results_text.Visible='on';
                        app.edit_results.Visible='on';
                        app.edit_results.Value=out1;
                    end
            %
            end
            %
            if(app.n_analysis==2)  % source frequency
            %
                fa=str2num(app.edit_apparent_frequency.Value);
                 u=str2num(app.edit_source_velocity.Value);
                 v=str2num(app.edit_receiver_velocity.Value);
            %
                app.text_results_text.Text='Source Frequency (Hz)';
            %
                    if(u>=c)
                        warndlg('Source velocity must be < speed of sound.','Warning')
                    end
                    if(v>=c)
                        warndlg('Receiver velocity must be < speed of sound.','Warning');
                    end
                    if(u<c && v<c)
                        clear fs;
            			fs=fa/((c-v)/(c-u));
                        out1=sprintf('%8.4g',fs);
                        app.text_results_text.Visible='on';
                        app.edit_results.Visible='on';
                        app.edit_results.Value=out1;
                    end
            %
            end
            %
            if(app.n_analysis==3)  % source velocity
            %
                fs=str2num(app.edit_source_frequency.Value);
                fa=str2num(app.edit_apparent_frequency.Value);
                 v=str2num(app.edit_receiver_velocity.Value);
            %
                if(app.n_unit==1)
                    app.text_results_text.Text='Source Velocity (ft/sec)';
                end
                if(app.n_unit==2)
                    app.text_results_text.Text='Source Velocity (mph)';
                end
                if(app.n_unit==3)
                    app.text_results_text.Text='Source Velocity (m/sec)';
                end
                if(app.n_unit==4)
                    app.text_results_text.Text='Source Velocity (km/hr)';
                end
            %
                if(v>=c)
                    warndlg('Receiver velocity must be < speed of sound','Warning');
                else
                    clear u;
                    u=-((fs/fa)*(c-v))+c;
                    out1=sprintf('%8.4g',u);
                    app.text_results_text.Visible='on';
                    app.edit_results.Visible='on';
                    app.edit_results.Value=out1;
                end
            %
            end
            if(app.n_analysis==4)  % receiver velocity
            %
                fs=str2num(app.edit_source_frequency.Value);
                fa=str2num(app.edit_apparent_frequency.Value);
                 u=str2num(app.edit_source_velocity.Value);
            %
                if(app.n_unit==1)
                    app.text_results_text.Text='Receiver Velocity (ft/sec)';
                end
                if(app.n_unit==2)
                    app.text_results_text.Text='Receiver Velocity (mph)';
                end
                if(app.n_unit==3)
                    app.text_results_text.Text='Receiver Velocity (m/sec)';
                end
                if(app.n_unit==4)
                    app.text_results_text.Text='Receiver Velocity (km/hr)';
                end
            %
                if(u>=c)
                    warndlg('Source velocity must be < speed of sound.','Warning');
                else
                    clear v;
                    v=-((fa/fs)*(c-u))+c;
                    out1=sprintf('%8.4g',v);
                    app.text_results_text.Visible='on';
                    app.edit_results.Visible='on';
                    app.edit_results.Value=out1;
                end
            %
            end
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_doppler';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'vibrationdata_doppler.m  ver 1.0  by Tom Irvine';
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Enter Data';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Velocity Unit';
            app.listbox_units = uilistbox(app.uipanel1);
            app.listbox_units.Items = {'ft/sec', 'mph', 'm/sec', 'km/hr'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'ft/sec';
            app.text_speed_sound = uilabel(app.uipanel1);
            app.text_speed_sound.Tag = 'text_speed_sound';
            app.text_speed_sound.Text = 'Speed of Sound (ft/sec)';
            app.edit_speed = uieditfield(app.uipanel1, 'text');
            app.edit_speed.Tag = 'edit_speed';
            app.edit_speed.Value = '1125';
            app.listbox_type = uilistbox(app.uipanel1);
            app.listbox_type.Items = {'Apparent Frequency', 'Source Frequency', 'Source Velocity', 'Receiver Velocity'};
            app.listbox_type.ValueChangedFcn = createCallbackFcn(app, @listbox_type_Callback, true);
            app.listbox_type.Tag = 'listbox_type';
            app.listbox_type.Value = 'Apparent Frequency';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Calculate';
            app.edit_source_frequency = uieditfield(app.uipanel1, 'text');
            app.edit_source_frequency.Tag = 'edit_source_frequency';
            app.edit_apparent_frequency = uieditfield(app.uipanel1, 'text');
            app.edit_apparent_frequency.Tag = 'edit_apparent_frequency';
            app.edit_receiver_velocity = uieditfield(app.uipanel1, 'text');
            app.edit_receiver_velocity.Tag = 'edit_receiver_velocity';
            app.edit_receiver_velocity.Value = '0';
            app.text_source_frequency = uilabel(app.uipanel1);
            app.text_source_frequency.Tag = 'text_source_frequency';
            app.text_source_frequency.Text = 'Source Frequency (Hz)';
            app.text_apparent_frequency = uilabel(app.uipanel1);
            app.text_apparent_frequency.Tag = 'text_apparent_frequency';
            app.text_apparent_frequency.Text = 'Apparent Frequency (Hz)';
            app.text_source_velocity = uilabel(app.uipanel1);
            app.text_source_velocity.Tag = 'text_source_velocity';
            app.text_source_velocity.Text = 'Source Velocity (ft/sec)';
            app.text_receiver_velocity = uilabel(app.uipanel1);
            app.text_receiver_velocity.Tag = 'text_receiver_velocity';
            app.text_receiver_velocity.Text = 'Receiver Velocity (ft/sec)';
            app.edit_source_velocity = uieditfield(app.uipanel1, 'text');
            app.edit_source_velocity.Tag = 'edit_source_velocity';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Results';
            app.uipanel2.Tag = 'uipanel2';
            app.edit_results = uieditfield(app.uipanel2, 'text');
            app.edit_results.Tag = 'edit_results';
            app.text_results_text = uilabel(app.uipanel2);
            app.text_results_text.Tag = 'text_results_text';
            app.text_results_text.Text = 'Apparent Frequency (Hz)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_doppler_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_doppler_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end