classdef vibrationdata_fourier_transform_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataFourierTransform  matlab.ui.Figure
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputTypeListBox               matlab.ui.control.ListBox
        OutputTypeListBoxLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        CalculateFourierTransformButton  matlab.ui.control.Button
        CalculateFFTButton              matlab.ui.control.Button
        SignalProcessingListBox         matlab.ui.control.ListBox
        SignalProcessingListBoxLabel    matlab.ui.control.Label
        PlotMaxFreqHzEditField          matlab.ui.control.NumericEditField
        PlotMaxFreqHzEditFieldLabel     matlab.ui.control.Label
        TimeHistoryYaxisLabelEditField  matlab.ui.control.EditField
        TimeHistoryYaxisLabelEditFieldLabel  matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        WindowListBoxLabel              matlab.ui.control.Label
        MeanRemovalListBox              matlab.ui.control.ListBox
        MeanRemovalListBoxLabel         matlab.ui.control.Label
        InputArrayNameEditField         matlab.ui.control.EditField
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        vibrationdata_fourier_transform_abyTomIrvinever12Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM    % Time history
        fmax   % Maximum frequency
        tstart % Start time
        tend   % End time
        df     % Freq step (Hz)
        dt     % Time step (sec)
        amp    % Amplitude
        ttt    %
        nhalf  %
        num    %
        z      %  
        zz     %
        f_real %
        f_imag %
        ms     %
        freq   %
        ff     %   
        full   %
        phase  %
        magnitude_FT       % 
        magnitude_phase_FT %
        complex_FT         %
        yname              %
        hw_choice          %
        Gxx     %
        iflag   %
    end
    
    methods (Access = private)
        
        function TimeChoice(app)
            n=app.SignalProcessingListBox.Value;
            if(contains(n,'Entire'))
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditField.Visible='off'; 
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';                 
            else    
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';              
            end
        end
        
        function PreProcess(app)
        
            ClosePlots(app);
            
            app.iflag=1;
        
            FS = app.InputArrayNameEditField.Value;
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end    
            
            app.fmax=app.PlotMaxFreqHzEditField.Value;
            
            nmr=app.MeanRemovalListBox.Value;
            
            if(contains(nmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            nhw=app.WindowListBox.Value;
            
            if(contains(nhw,'Rectangular'))
                app.hw_choice=1;
            else
                app.hw_choice=2;
            end
            
            
            app.yname =app.TimeHistoryYaxisLabelEditField.Value;            

            ns=app.SignalProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
              
            app.amp=app.THM(:,2);
            
            app.num=length(app.amp);
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.amp(1:app.num);
            app.ttt=app.THM(1:app.num,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            
            if(app.fmax==0) 
                sr=1/app.dt;
                app.fmax=sr/2;
            end
            
            fprintf('\n num=%d  dt=%8.4g  \n\n',app.num,app.dt);
            
            [app.amp]=mean_removal_Hanning(app.amp,mr_choice,app.hw_choice);

        end
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end                      
        end
        
        function PostProcess(app) 
            fig_num=1;
        
            if(app.hw_choice==1)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.ttt,app.amp);
                title('Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
            else
                figure(fig_num);
                fig_num=fig_num+1;
                
                subplot(2,1,1);   
                plot(app.THM(:,1),app.THM(:,2));
                title('Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
                
                subplot(2,1,2); 
                plot(app.ttt,app.amp);
                title('Time History with Hanning Window');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]); 
                grid on;    
            end
            
            [~,I]=max(app.magnitude_FT(:,2));
            fmaxp=app.magnitude_FT(I,1);
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.magnitude_FT(:,1),app.magnitude_FT(:,2));
            out1=sprintf('Fourier Transform Magnitude  Max Peak at %8.4g Hz',fmaxp);
            title(out1);
            ylabel(app.yname);
            xlabel('Frequency (Hz)');
            xlim([0 app.fmax]);
            grid on;     
            
            md=4;
            ylab=app.yname;
            fmin=0;
            
            fff=app.magnitude_phase_FT(:,1);
            FRF_m=app.magnitude_phase_FT(:,2);            
            FRF_p=app.magnitude_phase_FT(:,3);
            t_string=sprintf('Fourier Transform Magnitude & Phase  Max Peak at %8.4g Hz',fmaxp);

            [fig_num]=...
                plot_magnitude_phase_function_linear(fig_num,t_string,fmin,app.fmax,fff,FRF_p,FRF_m,ylab,md);            
            
            nnum=length(fff);
            
            app.complex_FT(:,2)=app.complex_FT(:,2)+(1i)*app.complex_FT(:,3);
            app.complex_FT(:,3)=[];
            
            app.Gxx=zeros(nnum,1);
            app.Gxx(1)=abs(app.complex_FT(1,2));     
            
            for i=2:nnum
                 X=app.complex_FT(i,2);     
            	app.Gxx(i)=transpose(X)*X;
            end
            
            app.Gxx=[fff abs(app.Gxx)];                
                
            app.SaveButton.Enable='on'; 

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TimeChoice(app);
            app.SaveButton.Enable='off';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            n=app.OutputTypeListBox.Value;
            
            if(strcmp(n,'FT Magnitude'))
                data=app.magnitude_FT;
            end
            if(strcmp(n,'FT Magnitude & Phase'))
                data=app.magnitude_phase_FT;
            end
            if(strcmp(n,'Complex FT'))
                data=app.complex_FT;
            end
            if(strcmp(n,'Power Spectrum'))
                data=app.Gxx;
            end            

            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name, data); 
            msgbox('Data saved');                  
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            
            PreProcess(app);
            
            if(app.iflag==1)
                [app.freq,app.full,app.phase,app.complex_FT]=full_FFT_core_alt(app.amp,app.num,app.df);
                app.magnitude_FT=[app.freq app.full];
                app.magnitude_phase_FT=[app.freq app.full app.phase];
                PostProcess(app);
            end
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)

            TimeChoice(app);
        end

        % Button pushed function: CalculateFourierTransformButton
        function CalculateFourierTransformButtonPushed(app, event)

            PreProcess(app);

            if(app.iflag==1)
            
                [app.z,app.zz,app.f_real,app.f_imag,app.ms,app.freq,app.ff]=...
                    fourier_core(app.num,app.nhalf,app.df,app.amp);
        
                app.phase=atan2(app.f_imag,app.f_real);
                app.phase=fix_size(app.phase);

                app.phase = app.phase*180/pi;

                app.magnitude_FT=[app.ff app.zz];
            
                size(app.magnitude_FT)
            
                app.magnitude_phase_FT=[app.ff app.zz app.phase(1:length(app.ff))];
                app.complex_FT=[app.freq app.f_real app.f_imag];
                PostProcess(app);   
            
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataFourierTransform = uifigure('Visible', 'off');
            app.UIVibrationdataFourierTransform.Name = 'UI vibrationdata_fourier_transform_a';
            app.vibrationdata_fourier_transform_abyTomIrvinever12Label = uilabel(app.UIVibrationdataFourierTransform);
            app.vibrationdata_fourier_transform_abyTomIrvinever12Label.Text = 'vibrationdata_fourier_transform_a   by Tom Irvine   ver 1.2';
            app.InputArrayNameEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.MeanRemovalListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.WindowListBox.Items = {'Rectangular', 'Hanning'};
            app.WindowListBox.Value = 'Rectangular';
            app.TimeHistoryYaxisLabelEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TimeHistoryYaxisLabelEditFieldLabel.Text = 'Time History Y-axis Label';
            app.TimeHistoryYaxisLabelEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.PlotMaxFreqHzEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.PlotMaxFreqHzEditFieldLabel.Text = 'Plot Max Freq (Hz)';
            app.PlotMaxFreqHzEditField = uieditfield(app.UIVibrationdataFourierTransform, 'numeric');
            app.SignalProcessingListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.CalculateFFTButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.CalculateFourierTransformButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.CalculateFourierTransformButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFourierTransformButtonPushed, true);
            app.CalculateFourierTransformButton.Text = 'Calculate Fourier Transform';
            app.ReturnButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.UIVibrationdataFourierTransform);
            app.Label.Text = {'Note that the Truncate/Zero Pad option has been removed since Matlab''s FFT algorithm family can now accept any number of points.'; ''};
            app.OutputTypeListBoxLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.OutputTypeListBoxLabel.Text = 'Output Type';
            app.OutputTypeListBox = uilistbox(app.UIVibrationdataFourierTransform);
            app.OutputTypeListBox.Items = {'FT Magnitude', 'FT Magnitude & Phase', 'Complex FT', 'Power Spectrum'};
            app.OutputTypeListBox.Value = 'FT Magnitude';
            app.OutputArrayNameEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.UIVibrationdataFourierTransform, 'text');
            app.SaveButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StartTimesecEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.UIVibrationdataFourierTransform, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.UIVibrationdataFourierTransform, 'numeric');
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec) & amplitude';
            % Show the figure after all components are created
            app.UIVibrationdataFourierTransform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_fourier_transform_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIVibrationdataFourierTransform)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataFourierTransform)
        end
    end
end