classdef vibrationdata_fourier_transform_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataFourierTransform  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        CalculateFourierTransformButton  matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        SignalProcessingListBox         matlab.ui.control.ListBox
        SignalProcessingListBoxLabel    matlab.ui.control.Label
        PlotMaxFreqHzEditField          matlab.ui.control.NumericEditField
        PlotMaxFreqHzEditFieldLabel     matlab.ui.control.Label
        TimeHistoryYaxisLabelEditField  matlab.ui.control.EditField
        TimeHistoryYaxisLabelEditFieldLabel  matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        WindowListBoxLabel              matlab.ui.control.Label
        MeanRemovalListBox              matlab.ui.control.ListBox
        MeanRemovalListBoxLabel         matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateFFTButton              matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        PreprocessedTimeHistoryTextArea  matlab.ui.control.TextArea
        PreprocessedTimeHistoryTextAreaLabel  matlab.ui.control.Label
        SaveOutputTab                   matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        OutputTypeListBox               matlab.ui.control.ListBox
        OutputTypeListBoxLabel          matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ExcelTab                        matlab.ui.container.Tab
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        OpenFTMagnitudeinExcelButton    matlab.ui.control.Button
        ExportFTMagnitudetoExcelFormatButton  matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        vibrationdata_fourier_transform_ahbyTomIrvinever12Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THMO   %
        THM    % Time history
        fmax   % Maximum frequency
        tstart % Start time
        tend   % End time
        df     % Freq step (Hz)
        dt     % Time step (sec)
        amp    % Amplitude
        ttt    %
        nhalf  %
        num    %
        z      %  
        zz     %
        f_real %
        f_imag %
        ms     %
        freq   %
        ff     %   
        full   %
        phase  %
        magnitude_FT       % 
        magnitude_phase_FT %
        complex_FT         %
        yname              %
        hw_choice          %
        Gxx     %
        iflag   %
        fstring %
        writepfname %
    end
    
    methods (Access = private)
        
        function TimeChoice(app)
            n=app.SignalProcessingListBox.Value;
            if(contains(n,'Entire'))
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditField.Visible='off'; 
                app.StartTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';                 
            else    
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.StartTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';              
            end
        end
        
        function PreProcess(app)
        
            ClosePlots(app);
            
            app.iflag=1;
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            if(iflagq~=1)
                app.iflag=999;
	            return
            end

            app.THMO=app.THM;
            
            app.fmax=app.PlotMaxFreqHzEditField.Value;
            
            nmr=app.MeanRemovalListBox.Value;
            
            if(contains(nmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            app.hw_choice = find(strcmp(Items, im));
            
            app.yname =app.TimeHistoryYaxisLabelEditField.Value;            

            ns=app.SignalProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end
              
            app.amp=app.THM(:,2);
            
            app.num=length(app.amp);
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.amp(1:app.num);
            app.ttt=app.THM(1:app.num,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            
            if(app.fmax==0) 
                sr=1/app.dt;
                app.fmax=sr/2;
            end
            
            [app.amp]=mean_removal_Hanning(app.amp,mr_choice,app.hw_choice);

            app.num=length(app.amp);
            
            fprintf('\n num=%d  dt=%8.4g  \n\n',app.num,app.dt);

            app.amp=fix_size(app.amp);
            
            app.THM=[app.THM(1:app.num,1) app.amp];

        end
        
        function ClosePlots(app)
            for i=1:120
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                  
        end      
        
        function PostProcess(app) 
            fig_num=1;
        
            if(app.hw_choice==1)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THMO(:,1),app.THMO(:,2));
                title('Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
            else
                figure(fig_num);
                fig_num=fig_num+1;
                
                subplot(2,1,1);   
                plot(app.THMO(:,1),app.THMO(:,2));
                title('Time History');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]);
                grid on;
                
                subplot(2,1,2); 
                plot(app.ttt,app.amp);
                title('Time History with Hanning Window');
                ylabel(app.yname);
                xlabel('Time (sec)');
                xlim([app.ttt(1) app.ttt(end)]); 
                grid on;    
            end
            
            [~,I]=max(app.magnitude_FT(:,2));
            fmaxp=app.magnitude_FT(I,1);
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(app.magnitude_FT(:,1),app.magnitude_FT(:,2));
            out1=sprintf('%s Magnitude  Max Peak at %8.4g Hz',app.fstring,fmaxp);
            title(out1);
            ylabel(app.yname);
            xlabel('Frequency (Hz)');
            xlim([0 app.fmax]);
            grid on;     
            
            md=4;
            ylab=app.yname;
            fmin=0;
            
            fff=app.magnitude_phase_FT(:,1);
            FRF_m=app.magnitude_phase_FT(:,2);            
            FRF_p=app.magnitude_phase_FT(:,3);
            t_string=sprintf('%s Magnitude & Phase  Max Peak at %8.4g Hz',app.fstring,fmaxp);

            [fig_num]=...
                plot_magnitude_phase_function_linear(fig_num,t_string,fmin,app.fmax,fff,FRF_p,FRF_m,ylab,md);            
            
            nnum=length(fff);
            
            app.complex_FT(:,2)=app.complex_FT(:,2)+(1i)*app.complex_FT(:,3);
            app.complex_FT(:,3)=[];
            
            app.Gxx=zeros(nnum,1);
            app.Gxx(1)=abs(app.complex_FT(1,2));     
            
            for i=2:nnum
                 X=app.complex_FT(i,2);     
            	app.Gxx(i)=transpose(X)*X;
            end
            
            app.Gxx=[fff abs(app.Gxx)];                
                
            app.SaveButton.Enable='on'; 

        end
        
        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TimeChoice(app);
            app.SaveButton.Enable='off';
            update(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            n=app.OutputTypeListBox.Value;
            
            if(strcmp(n,'FT Magnitude'))
                data=app.magnitude_FT;
            end
            if(strcmp(n,'FT Magnitude & Phase'))
                data=app.magnitude_phase_FT;
            end
            if(strcmp(n,'Complex FT'))
                data=app.complex_FT;
            end
            if(strcmp(n,'Power Spectrum'))
                data=app.Gxx;
            end            
            if(strcmp(n,'Time History'))
                data=[app.ttt,app.amp];
            end    

            output_name=app.OutputArrayNameEditField.Value;
            assignin('base',output_name, data); 
            msgbox('Data saved');                  
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            
            app.fstring='FFT';

            PreProcess(app);

            if(app.num<=1)
                warndlg(' app.num error');
                return;
            end    
            
            if(app.iflag==1)
                [app.freq,app.full,app.phase,app.complex_FT]=full_FFT_core_alt(app.amp,app.num,app.df);
                app.magnitude_FT=[app.freq app.full];
                app.magnitude_phase_FT=[app.freq app.full app.phase];
                PostProcess(app);
            end
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)

            TimeChoice(app);
        end

        % Button pushed function: CalculateFourierTransformButton
        function CalculateFourierTransformButtonPushed(app, event)

             app.fstring='Fourier Transform';

            PreProcess(app);

            if(app.iflag==1)
            
                [app.z,app.zz,app.f_real,app.f_imag,app.ms,app.freq,app.ff]=...
                    fourier_core(app.num,app.nhalf,app.df,app.amp);
        
                app.phase=atan2(app.f_imag,app.f_real);
                app.phase=fix_size(app.phase);

                app.phase = app.phase*180/pi;

                app.magnitude_FT=[app.ff app.zz];
            
                size(app.magnitude_FT)
            
                app.magnitude_phase_FT=[app.ff app.zz app.phase(1:length(app.ff))];
                app.complex_FT=[app.freq app.f_real app.f_imag];
                PostProcess(app);   
            
            end
            
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.PreprocessedTimeHistoryTextArea.Value=' ';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        
            a=app.THM(:,2);

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.PreprocessedTimeHistoryTextArea.Value=ss;

        end

        % Button pushed function: ExportFTMagnitudetoExcelFormatButton
        function ExportFTMagnitudetoExcelFormatButtonPushed(app, event)

            [writefname, writepname] = uiputfile('*.xlsx','Save model as Excel file');
            app.writepfname = fullfile(writepname, writefname);
            
            writematrix(app.magnitude_FT,app.writepfname);

            msgbox('Save complete');
        end

        % Button pushed function: OpenFTMagnitudeinExcelButton
        function OpenFTMagnitudeinExcelButtonPushed(app, event)
            winopen(app.writepfname);
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            app.hw_choice = find(strcmp(Items, im));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataFourierTransform = uifigure('Visible', 'off');
            app.UIVibrationdataFourierTransform.Name = 'Fourier Transform';
            app.vibrationdata_fourier_transform_ahbyTomIrvinever12Label = uilabel(app.UIVibrationdataFourierTransform);
            app.vibrationdata_fourier_transform_ahbyTomIrvinever12Label.Text = 'vibrationdata_fourier_transform_ah   by Tom Irvine   ver 1.9       The input array must have two columns:  time (sec) & amplitude.';
            app.ReturnButton = uibutton(app.UIVibrationdataFourierTransform, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.Label = uilabel(app.UIVibrationdataFourierTransform);
            app.Label.Text = {'Note that the Truncate/Zero Pad option has been removed since Matlab''s FFT algorithm family can now accept any number of points.'; ''};
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.UIVibrationdataFourierTransform);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec) & amplitude';
            app.TabGroup = uitabgroup(app.UIVibrationdataFourierTransform);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateFFTButton = uibutton(app.CalculateTab, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.listbox_method = uilistbox(app.CalculateTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.CalculateTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.CalculateTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.MeanRemovalListBoxLabel = uilabel(app.CalculateTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.CalculateTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.CalculateTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.CalculateTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Rectangular';
            app.TimeHistoryYaxisLabelEditFieldLabel = uilabel(app.CalculateTab);
            app.TimeHistoryYaxisLabelEditFieldLabel.Text = 'Time History Y-axis Label';
            app.TimeHistoryYaxisLabelEditField = uieditfield(app.CalculateTab, 'text');
            app.PlotMaxFreqHzEditFieldLabel = uilabel(app.CalculateTab);
            app.PlotMaxFreqHzEditFieldLabel.Text = 'Plot Max Freq (Hz)';
            app.PlotMaxFreqHzEditField = uieditfield(app.CalculateTab, 'numeric');
            app.SignalProcessingListBoxLabel = uilabel(app.CalculateTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.CalculateTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.CalculateTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.CalculateTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.CalculateTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.CalculateTab, 'numeric');
            app.SelectInputArrayListBoxLabel = uilabel(app.CalculateTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.CalculateTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.CalculateFourierTransformButton = uibutton(app.CalculateTab, 'push');
            app.CalculateFourierTransformButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFourierTransformButtonPushed, true);
            app.CalculateFourierTransformButton.Text = 'Calculate Fourier Transform';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.PreprocessedTimeHistoryTextAreaLabel = uilabel(app.StatisticsTab);
            app.PreprocessedTimeHistoryTextAreaLabel.Text = 'Pre-processed Time History';
            app.PreprocessedTimeHistoryTextArea = uitextarea(app.StatisticsTab);
            app.CalculateButton = uibutton(app.StatisticsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveOutputTab = uitab(app.TabGroup);
            app.SaveOutputTab.Title = 'Save Output';
            app.SaveButton = uibutton(app.SaveOutputTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputTypeListBoxLabel = uilabel(app.SaveOutputTab);
            app.OutputTypeListBoxLabel.Text = 'Output Type';
            app.OutputTypeListBox = uilistbox(app.SaveOutputTab);
            app.OutputTypeListBox.Items = {'FT Magnitude', 'FT Magnitude & Phase', 'Complex FT', 'Power Spectrum', 'Time History'};
            app.OutputTypeListBox.Value = 'FT Magnitude';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveOutputTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveOutputTab, 'text');
            app.ExcelTab = uitab(app.TabGroup);
            app.ExcelTab.Title = 'Excel';
            app.ExportFTMagnitudetoExcelFormatButton = uibutton(app.ExcelTab, 'push');
            app.ExportFTMagnitudetoExcelFormatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFTMagnitudetoExcelFormatButtonPushed, true);
            app.ExportFTMagnitudetoExcelFormatButton.Text = 'Export FT Magnitude to Excel Format';
            app.OpenFTMagnitudeinExcelButton = uibutton(app.ExcelTab, 'push');
            app.OpenFTMagnitudeinExcelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFTMagnitudeinExcelButtonPushed, true);
            app.OpenFTMagnitudeinExcelButton.Text = 'Open FT Magnitude in Excel';
            app.Step1Label = uilabel(app.ExcelTab);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.ExcelTab);
            app.Step2Label.Text = 'Step 2';
            % Show the figure after all components are created
            app.UIVibrationdataFourierTransform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_fourier_transform_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIVibrationdataFourierTransform)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIVibrationdataFourierTransform)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataFourierTransform)
        end
    end
end