classdef vibrationdata_generalized_eigenvalue_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel2              matlab.ui.container.Panel
        text6                 matlab.ui.control.Label
        pushbutton_save       matlab.ui.control.Button
        edit_save_modeshapes  matlab.ui.control.EditField
        text5                 matlab.ui.control.Label
        edit_save_fn          matlab.ui.control.EditField
        text4                 matlab.ui.control.Label
        uipanel1              matlab.ui.container.Panel
        pushbutton_return     matlab.ui.control.Button
        pushbutton_calculate  matlab.ui.control.Button
        edit_stiffness        matlab.ui.control.EditField
        edit_mass             matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        text1                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_generalized_eigenvalue_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_generalized_eigenvalue (see VARARGIN)
            
            % Choose default command line output for vibrationdata_generalized_eigenvalue
            handles.output = hObject;

            app.uipanel2.Visible='off';
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            FS=get(handles.edit_mass,'String');
            mass=evalin('base',FS);
            
            FS=get(handles.edit_stiffness,'String');
            stiffness=evalin('base',FS);


            [fn,omega,ModeShapes,MST]=Generalized_Eigen(stiffness,mass,2);
            %
            disp('         Natural      ');
            disp('Mode   Frequency(Hz)  ');
            %
            mmm=MST*mass*ModeShapes;
            %
            clear length;
            dof=length(fn);
            
            set(handles.pushbutton_save,'Enable','off');
            
            if(dof>=1)
            
                msgbox('Results written to Matlab Command Window');
            
                for i=1:dof
                    out1 = sprintf('%d  %10.4g ',i,fn(i) );
                    disp(out1);
                end
            
                ModeShapes
            
                fn=fix_size(fn);
            
                setappdata(0,'fn',fn);
                setappdata(0,'ModeShapes',ModeShapes);
            
                set(handles.pushbutton_save,'Enable','on');
                app.uipanel2.Visible='on';

            end
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            fn = getappdata(0,'fn');
            ModeShapes =getappdata(0,'ModeShapes');
            
            fn_name=get(handles.edit_save_fn,'String');
            assignin('base', fn_name,fn);
            
            ModeShapes_name=get(handles.edit_save_modeshapes,'String');
            assignin('base',ModeShapes_name,ModeShapes);
            
            h = msgbox('Save Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_generalized_eigenvalue';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'vibrationdata_generalized_eigenvalue.m  ver 1.1  by Tom Irvine';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Enter Matlab Array Names';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Mass';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Stiffness';
            app.edit_mass = uieditfield(app.uipanel1, 'text');
            app.edit_mass.Tag = 'edit_mass';
            app.edit_stiffness = uieditfield(app.uipanel1, 'text');
            app.edit_stiffness.Tag = 'edit_stiffness';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Save Results to Matlab Workspace';
            app.uipanel2.Tag = 'uipanel2';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Natural Frequencies';
            app.edit_save_fn = uieditfield(app.uipanel2, 'text');
            app.edit_save_fn.Tag = 'edit_save_fn';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Mass-Normalized Mode Shapes';
            app.edit_save_modeshapes = uieditfield(app.uipanel2, 'text');
            app.edit_save_modeshapes.Tag = 'edit_save_modeshapes';
            app.pushbutton_save = uibutton(app.uipanel2, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Enable = 'off';
            app.pushbutton_save.Text = 'Save';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Enter Output Array Names';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_generalized_eigenvalue_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_generalized_eigenvalue_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end