classdef vibrationdata_integrate_differentiate_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        pushbutton_calculate  matlab.ui.control.Button
        pushbutton_return     matlab.ui.control.Button
        listbox_analysis      matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_integrate_differentiate_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_integrate_differentiate (see VARARGIN)
            
            % Choose default command line output for vibrationdata_integrate_differentiate
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_analysis,'Value');
            
            if(n==1)
               handles.s= vibrationdata_integrate;
            end
            if(n==2)
               handles.s= vibrationdata_integrate_double;
            end
            if(n==3)
               handles.s= vibrationdata_differentiate;
            end
            if(n==4)
               accel_vel_disp_correction_App
            end
            if(n==5)
               handles.s= vibrationdata_relative_displacement;
            end
            
            try
                set(handles.s,'Visible','on');
            catch
            end
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'vibrationdata_integrate_differentiate';
            app.figure1.Tag = 'figure1';
            app.listbox_analysis = uilistbox(app.figure1);
            app.listbox_analysis.Items = {'Integrate                                    ', 'Double Integrate Acceleration to Displacement', 'Differentiate                                ', 'Correct Acceleration, Velocity, Displacement for Oscillation about Zero Baseline', 'Relative Displacement from Two Acceleration Time Histories'};
            app.listbox_analysis.Tag = 'listbox_analysis';
            app.listbox_analysis.Value = 'Integrate                                    ';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'vibrationdata_integrate_differentiate.m  ver 1.2  by Tom Irvine';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Analysis';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_integrate_differentiate_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_integrate_differentiate_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end