classdef vibrationdata_integrate_double_alt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        text4                       matlab.ui.control.Label
        text3                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        uipanel1                    matlab.ui.container.Panel
        pushbutton_read_data        matlab.ui.control.Button
        text_fc                     matlab.ui.control.Label
        text18                      matlab.ui.control.Label
        listbox_taper_a             matlab.ui.control.ListBox
        listbox_integration_3       matlab.ui.control.ListBox
        text10                      matlab.ui.control.Label
        listbox_integration_2       matlab.ui.control.ListBox
        text9                       matlab.ui.control.Label
        listbox_integration_1       matlab.ui.control.ListBox
        text8                       matlab.ui.control.Label
        listbox_units               matlab.ui.control.ListBox
        text5                       matlab.ui.control.Label
        text16                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        listbox_taper_v             matlab.ui.control.ListBox
        text_taper                  matlab.ui.control.Label
        listbox_taper_d             matlab.ui.control.ListBox
        uipanel_save                matlab.ui.container.Panel
        SelectExtensonListBox       matlab.ui.control.ListBox
        SelectExtensonListBoxLabel  matlab.ui.control.Label
        pushbutton_save             matlab.ui.control.Button
        text12                      matlab.ui.control.Label
        listbox_output              matlab.ui.control.ListBox
        pushbutton_return           matlab.ui.control.Button
        pushbutton_calculate        matlab.ui.control.Button
        edit_fc                     matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function listbox_taper_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox_taper (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        
        function listbox_integration(app)
            
            app.uipanel_save.Visible='off';
            
            n=strtrim(app.listbox_integration_1.Value);
            
            if(contains(n,'Highpass'))
                app.edit_fc.Visible='on';
                app.text_fc.Visible='on';
            else
                app.edit_fc.Visible='off';
                app.text_fc.Visible='off';
            end
        end

        function CloseFigures(app)
             try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_integrate_double_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_integrate_double (see VARARGIN)
            
            % Choose default command line output for vibrationdata_integrate_double
            handles.output = hObject;
            
            app.pushbutton_calculate.Enable='off';

            listbox_integration(app);


            set(handles.listbox_integration_1,'Value',1);
            set(handles.listbox_integration_2,'Value',1);
            set(handles.listbox_integration_3,'Value',1);
            
            set(handles.uipanel_save,'Visible','off');
           
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_integration_1
        function listbox_integration_1_Callback(app, event)
            
            listbox_integration(app);
            
        end

        % Value changed function: listbox_integration_2
        function listbox_integration_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_integration_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_integration_2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_integration_2
            
            set(handles.uipanel_save,'Visible','off');
        end

        % Value changed function: listbox_integration_3
        function listbox_integration_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_integration_3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_integration_3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_integration_3
            
            set(handles.uipanel_save,'Visible','off');
        end

        % Callback function
        function listbox_method_Callback(app, event)
            
        end

        % Value changed function: listbox_units
        function listbox_units_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_units (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_units contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_units
            set(handles.uipanel_save,'Visible','off');
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            CloseFigures(app);
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            
            iunit=get(handles.listbox_units,'Value');
            setappdata(0,'iunit',iunit);
            

            THM=getappdata(0,'THM');
          
            
            tt=THM(:,1);
            
            y=double(THM(:,2));
            
            num=length(y);
            
            dur=THM(num,1)-THM(1,1);
            
            dt=dur/(num-1);
            
            
            %%%
            
            I1=get(handles.listbox_integration_1,'Value');
            I2=get(handles.listbox_integration_2,'Value');
            I3=get(handles.listbox_integration_3,'Value');
            
            %%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 1
            
            if(I1==2)
                y=y-mean(y);
            end
            if(I1==3)
                y=detrend(y);
            end
            if(I1==4)
                n = 2;
                p = polyfit(tt,y,n);
                y= y - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I1==5)
                fc=str2num(get(handles.edit_fc,'String'));
                y=detrend(y);
                % old-school method is more stable than Matlab's filter
                [y]=Butterworth_filter_highpass_function(y,fc,dt);
            end


            ntap=get(handles.listbox_taper_a,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.5;
                end
                if(ntap==3)
                    npe=1;
                end
                if(ntap==4)
                    npe=2;
                end
                if(ntap==5)
                    npe=3;
                end
                if(ntap==6)
                    npe=4;
                end
                if(ntap==7)
                    npe=5;
                end
                if(ntap==8)
                    npe=6;
                end
                if(ntap==9)
                    npe=7;
                end
                if(ntap==10)
                    npe=8;
                end
                if(ntap==11)
                    npe=9;
                end
                if(ntap==12)
                    npe=10;
                end
            %
                [y]=half_cosine_fade_perc(y,npe);
            
            end
            
            fprintf('\n dt=%8.4g sec   ntap=%d \n',dt,ntap);
            
            
            a=y;
            v=dt*cumtrapz(a);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % In Between
            
            if(I2==2)
                v=v-mean(v);
            end
            if(I2==3)
                v=detrend(v);
            end
            if(I2==4)
                n = 2;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I2==5)
                n = 3;
                p = polyfit(tt,v,n);
                v= v - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            
            ntap=get(handles.listbox_taper_v,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.5;
                end
                if(ntap==3)
                    npe=1;
                end
                if(ntap==4)
                    npe=2;
                end
                if(ntap==5)
                    npe=3;
                end
                if(ntap==6)
                    npe=4;
                end
                if(ntap==7)
                    npe=5;
                end
                if(ntap==8)
                    npe=6;
                end
                if(ntap==9)
                    npe=7;
                end
                if(ntap==10)
                    npe=8;
                end
                if(ntap==11)
                    npe=9;
                end
                if(ntap==12)
                    npe=10;
                end
            %
                [v]=half_cosine_fade_perc(v,npe);
            
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Integration 2
           
            d=dt*cumtrapz(v);
            
            d=fix_size(d);
            
            if(I3==2)
                d=d-mean(d);
            end
            if(I3==3)
                d=detrend(d);
            end
            if(I3==4)
                n = 2;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^2 + p(2)*tt + p(3));
            end
            if(I3==5)
                n = 3;
                p = polyfit(tt,d,n);
                d= d - (  p(1)*tt.^3 + p(2)*tt.^2 + p(3)*tt + p(4));
            end
            
            ntap=get(handles.listbox_taper_d,'Value');
            
            if(ntap>1)
                if(ntap==2)
                    npe=0.5;
                end
                if(ntap==3)
                    npe=1;
                end
                if(ntap==4)
                    npe=2;
                end
                if(ntap==5)
                    npe=3;
                end
                if(ntap==6)
                    npe=4;
                end
                if(ntap==7)
                    npe=5;
                end
                if(ntap==8)
                    npe=6;
                end
                if(ntap==9)
                    npe=7;
                end
                if(ntap==10)
                    npe=8;
                end
                if(ntap==11)
                    npe=9;
                end
                if(ntap==12)
                    npe=10;
                end
            %
                [d]=half_cosine_fade_perc(d,npe);
            %
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a=fix_size(a);
            v=fix_size(v);
            d=fix_size(d);
            
            if(iunit==1)
                v=v*386;
                d=d*386;
                ay='Accel (G)';
                vy='Vel (in/sec)';
                dy='Disp (in)';
            end
            if(iunit==2)
                v=v*9.81*100;
                d=d*9.81*1000;
                ay='Accel (G)';
                vy='Vel (cm/sec)';
                dy='Disp (mm)';
            end
            if(iunit==3)
                v=v*100;
                d=d*1000;
                ay='Accel (m/sec^2)';
                vy='Vel (cm/sec)';
                dy='Disp (mm)';
            end
            
            fig_num=1;

            try
            
                [fig_num,~]=plot_avd_time_histories_subplots_altp(fig_num,tt,a,v,d,ay,vy,dy);
            catch
                warndlg('Plot failed');
                return;
            end

            
            %%%
            
            set(handles.uipanel_save,'Visible','on');
            
            aa=[tt a];
            vv=[tt v];
            dd=[tt d];
            
            setappdata(0,'acceleration',aa);
            setappdata(0,'velocity',vv);
            setappdata(0,'displacement',dd);
            setappdata(0,'dt',dt);
            setappdata(0,'ay',ay);
            setappdata(0,'vy',vy);
            setappdata(0,'dy',dy);
            
            disp(' ');
                    disp('                 max     min     std ')
            
            out1=sprintf(' acceleration: %8.4g %8.4g %8.4g ',max(a),min(a),std(a));
            out2=sprintf('     velocity: %8.4g %8.4g %8.4g ',max(v),min(v),std(v));
            out3=sprintf(' displacement: %8.4g %8.4g %8.4g  ',max(d),min(d),std(d));
            
            disp(out1);
            disp(out2);
            disp(out3);
            
            setappdata(0,'fig_num',fig_num);
        end

        % Callback function
        function pushbutton_differentiate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_differentiate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.s=vibrationdata_differentiate_displacement;
            
            set(handles.s,'Visible','on');
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            m=app.SelectExtensonListBox.Value;

                if(contains(m,'cvs'))
                end    
                if(contains(m,'xls'))
                end    
                if(contains(m,'txt'))
                end 

            n=get(handles.listbox_output,'Value');

            n
            m
            
            if(n==1)
                data=getappdata(0,'acceleration');

                if(contains(m,'csv'))
                    [writefname, writepname] = uiputfile('*.csv','Save acceleration data as');
                end    
                if(contains(m,'xls'))
                    [writefname, writepname] = uiputfile('*.xls','Save acceleration data as');
                end    
                if(contains(m,'txt'))
                    [writefname, writepname] = uiputfile('*.txt','Save acceleration data as');
                end 
            end
            if(n==2)
                data=getappdata(0,'velocity');
                if(contains(m,'csv'))
                    [writefname, writepname] = uiputfile('*.csv','Save velocity data as');
                end    
                if(contains(m,'xls'))
                    [writefname, writepname] = uiputfile('*.xls','Save velocity data as');
                end    
                if(contains(m,'txt'))
                    [writefname, writepname] = uiputfile('*.txt','Save velocity data as');
                end 
            end
            if(n==3)
                data=getappdata(0,'displacement');
                if(contains(m,'csv'))
                    [writefname, writepname] = uiputfile('*.csv','Save displacement data as');
                end    
                if(contains(m,'xls'))
                    [writefname, writepname] = uiputfile('*.xls','Save displacement data as');
                end    
                if(contains(m,'txt'))
                    [writefname, writepname] = uiputfile('*.txt','Save displacement data as');
                end     
            end

            writepfname = fullfile(writepname, writefname);
            
            writematrix(data,writepfname);

            msgbox('Save Complete');

        end

        % Button pushed function: pushbutton_read_data
        function pushbutton_read_dataButtonPushed(app, event)
               [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                           

               try
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   THM = fscanf(fid,'%g %g',[2 inf]);
                   THM=THM';
            
                   setappdata(0,'THM',THM);
               catch
                   warndlg('Read failed');
                   return;
               end

               app.pushbutton_calculate.Enable='on';

               CloseFigures(app);
            
               iunit=get(handles.listbox_units,'Value');

               figure(1);
               plot(THM(:,1),THM(:,2));
               grid on;
               xlabel('Time (sec)');
               if(iunit<=3)
                    ylabel('Accel (G)');                  
               else
                    ylabel('Accel (m/sec^2)');                 
               end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'vibrationdata_integrate_double';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.edit_fc = uieditfield(app.uipanel1, 'text');
            app.edit_fc.Tag = 'edit_fc';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.uipanel_save = uipanel(app.uipanel1);
            app.uipanel_save.Title = 'Save';
            app.uipanel_save.Tag = 'uipanel_save';
            app.listbox_output = uilistbox(app.uipanel_save);
            app.listbox_output.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.listbox_output.Tag = 'listbox_output';
            app.listbox_output.Value = 'Acceleration';
            app.text12 = uilabel(app.uipanel_save);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Select Output';
            app.pushbutton_save = uibutton(app.uipanel_save, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.SelectExtensonListBoxLabel = uilabel(app.uipanel_save);
            app.SelectExtensonListBoxLabel.Text = 'Select Extenson';
            app.SelectExtensonListBox = uilistbox(app.uipanel_save);
            app.SelectExtensonListBox.Items = {'csv', 'xls', 'txt'};
            app.SelectExtensonListBox.Value = 'csv';
            app.listbox_taper_d = uilistbox(app.uipanel1);
            app.listbox_taper_d.Items = {'None', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_d.Tag = 'listbox_taper_d';
            app.listbox_taper_d.Value = 'None';
            app.text_taper = uilabel(app.uipanel1);
            app.text_taper.Tag = 'text_taper';
            app.text_taper.Text = 'Duration Percent Taper';
            app.listbox_taper_v = uilistbox(app.uipanel1);
            app.listbox_taper_v.Items = {'None', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_v.Tag = 'listbox_taper_v';
            app.listbox_taper_v.Value = 'None';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Velocity';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Displacement';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Select Units';
            app.listbox_units = uilistbox(app.uipanel1);
            app.listbox_units.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.listbox_units.ValueChangedFcn = createCallbackFcn(app, @listbox_units_Callback, true);
            app.listbox_units.Tag = 'listbox_units';
            app.listbox_units.Value = 'G, in/sec, in';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Select Trend Removal Prior to First Integration';
            app.listbox_integration_1 = uilistbox(app.uipanel1);
            app.listbox_integration_1.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Highpass Filter'};
            app.listbox_integration_1.ValueChangedFcn = createCallbackFcn(app, @listbox_integration_1_Callback, true);
            app.listbox_integration_1.Tag = 'listbox_integration_1';
            app.listbox_integration_1.Value = 'None';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Select Trend Removal Between Integrations';
            app.listbox_integration_2 = uilistbox(app.uipanel1);
            app.listbox_integration_2.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.listbox_integration_2.ValueChangedFcn = createCallbackFcn(app, @listbox_integration_2_Callback, true);
            app.listbox_integration_2.Tag = 'listbox_integration_2';
            app.listbox_integration_2.Value = 'None';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Select Trend Removal After Integration';
            app.listbox_integration_3 = uilistbox(app.uipanel1);
            app.listbox_integration_3.Items = {'None', 'Mean Removal', 'First Order Trend', 'Second Order Trend', 'Third Order Trend'};
            app.listbox_integration_3.ValueChangedFcn = createCallbackFcn(app, @listbox_integration_3_Callback, true);
            app.listbox_integration_3.Tag = 'listbox_integration_3';
            app.listbox_integration_3.Value = 'None';
            app.listbox_taper_a = uilistbox(app.uipanel1);
            app.listbox_taper_a.Items = {'None', '0.5', '1  ', '2  ', '3  ', '4  ', '5  ', '6', '7', '8', '9', '10'};
            app.listbox_taper_a.Tag = 'listbox_taper_a';
            app.listbox_taper_a.Value = 'None';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Acceleration';
            app.text_fc = uilabel(app.uipanel1);
            app.text_fc.Tag = 'text_fc';
            app.text_fc.Text = 'Enter Highpass Frequency (Hz)';
            app.pushbutton_read_data = uibutton(app.uipanel1, 'push');
            app.pushbutton_read_data.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_read_dataButtonPushed, true);
            app.pushbutton_read_data.Tag = 'pushbutton_calculate';
            app.pushbutton_read_data.Text = 'Read Data';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'vibrationdata_integrate_double_alt.m  ver 1.0  by Tom Irvine';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'This script integrates an acceleration time history to velocity and displacement.';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'The input array must have two columns: time(sec) & acceleration';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_integrate_double_alt_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_integrate_double_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end