classdef vibrationdata_psd_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIvibrationdatapsdUIFigure      matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DataEntryTab                    matlab.ui.container.Tab
        ApplyTimeLimitsButton           matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        EndTimesecEditField             matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        SignalProcessingListBox         matlab.ui.control.ListBox
        SignalProcessingListBoxLabel    matlab.ui.control.Label
        InputTypeListBox                matlab.ui.control.ListBox
        InputTypeListBoxLabel           matlab.ui.control.Label
        AmplitudeUnitEditField          matlab.ui.control.EditField
        AmplitudeUnitEditFieldLabel     matlab.ui.control.Label
        ReadDataButton                  matlab.ui.control.Button
        CalculatePSDTab                 matlab.ui.container.Tab
        InputArrayEditField             matlab.ui.control.EditField
        InputArrayEditFieldLabel        matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        WindowListBoxLabel              matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        PlotFrequenciesHzLabel          matlab.ui.control.Label
        MeanRemovalListBox              matlab.ui.control.ListBox
        MeanRemovalListBoxLabel         matlab.ui.control.Label
        ProcessingOptionsLabel          matlab.ui.control.Label
        SelectdofListBox                matlab.ui.control.ListBox
        SelectdofListBoxLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        ExportPSDTab                    matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SuperimposeSpecTab              matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        spec                            matlab.ui.control.Label
        InputSpecificationArrayNameEditField  matlab.ui.control.EditField
        InputSpecificationArrayNameEditFieldLabel  matlab.ui.control.Label
        ConverttoOctaveFormatTab        matlab.ui.container.Tab
        OutputPSDNameEditField          matlab.ui.control.EditField
        OutputPSDNameEditFieldLabel     matlab.ui.control.Label
        OutputOctaveFormatListBox       matlab.ui.control.ListBox
        OutputOctaveFormatListBoxLabel  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM     % time history array
        ttt     % time column
        amp     % amplitude column
        s1      % amplitude dimension
        s2      % amplitude unit
        dt      % time step
        df      % frequency step
        num     % number of points 
        nhalf   % half the number of points
        AA      % signal processing option array
        fig_num % figure number 
        Fourier_magnitude       % 
        power_spectral_density  % 
        fmin %
        fmax %
        rms  %
        x_label %
        y_label %
        N %
        iflag %
    end
    
    methods (Access = private)
        
        function Signal(app)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
                app.ApplyTimeLimitsButton.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.ApplyTimeLimitsButton.Visible='on';         
            end
        end

        function Choice(app)

            app.num=length(app.THM(:,1));
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.THM(:,2);
            app.ttt=app.THM(:,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            %%%
            
            app.N=floor(log(app.num)/log(2));
            
            if(app.N>12)
                app.N=12;
            end
            
            ss={'2','4','8','16','32','64','128','256','512','1024','2048','4096'};            
            sq={'1','2','3', '4', '5', '6',  '7',  '8',  '9',  '10',  '11', '12'};            
             
            A=zeros(app.N,5);
            
            for i=1:app.N
                m=2^(i-1); 
                A(i,1)=m;      
                A(i,2)=floor(app.num/m);
                A(i,3)=A(i,2)*app.dt;
                A(i,4)=1/A(i,3);
                A(i,5)=2*A(i,1);
            end  
            
            myData=array2table(A);
            app.UITable.Data=myData;  
            
            for i=10:-1:app.N
                ss(end)=[];
                sq(end)=[];                
            end
  
            app.SelectdofListBox.Items=ss;
            % app.UITable.RowName=sq;
            
            app.AA=A;
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            app.listbox_method.Value=app.listbox_method.Items{1};
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Signal(app);
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            app.fig_num=1;
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                     app.InputArrayEditField.Value=FS;
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            else
            
                try
            
                   [filename, pathname] = uigetfile('*.*');
                   app.InputArrayEditField.Value=filename;
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

                catch
                   warndlg('Read unsuccessful');
                   return;
                end

                try
                    [~, name, ~] = fileparts(filename);
                catch
                    warndlg('ref 1')
                end

                try
                    assignin('base',name,app.THM);
                catch
                    warndlg('ref 2')
                end

                [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
                if(iflagq~=1)
	                return
                end

               update(app);
               try
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end                
            
            if(isempty(app.THM))
                warndlg('Input Array not found')
                return;
            end    


            L=app.InputTypeListBox.Value;
            
            app.s1='';
            
            if(contains(L,'Acceleration'))
                app.s1='Accel';
            end
            if(contains(L,'Velocity'))
                app.s1='Velocity';
            end
            if(contains(L,'Displacement'))
                app.s1='Acceleration';
            end
            if(contains(L,'Force'))
                app.s1='Force';
            end
            if(contains(L,'Pressure'))
                app.s1='Pressure';
            end
              
            app.s2=app.AmplitudeUnitEditField.Value;
            
            app.spec.Text=sprintf('The input array must have two columns:  freq (Hz) & psd (%s^2/Hz)',app.s2);
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(app.iflag==999)
                return;
            end

            
            figure(app.fig_num);
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            xlabel('Time (sec)');
            ss=sprintf('%s (%s)',app.s1,app.s2);
            ylabel(ss);
            
            %%%
            
            Choice(app);
            
            msgbox('Apply Time Limits Or Go to Calculate PSD tab');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            if(app.iflag==999)
                return;
            end

            fprintf('\n\n * * * * * * * * \n\n');
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end
            if(max(size(app.THM))<=1)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end           
            if(app.N==0)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end               
        
            b=app.SelectdofListBox.Value;
            for i=1:app.N
                a=2^i;
                ss=sprintf('%d',a);
                
                if(strcmp(b,ss))
                    % dof=a;
                    num_seg=app.AA(i,1);
                    samples_seg=app.AA(i,2);
                    app.df=app.AA(i,4);
                    break;
                end
            end  
            
            NW=num_seg;
            
            mmm=samples_seg;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            mmr=app.MeanRemovalListBox.Value;
            
            if(strcmp(mmr,'Yes'))
                mr_choice=1;
            else
                mr_choice=2;
            end
            
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            h_choice = find(strcmp(Items, im));
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf('\n Begin psd_core \n\n');
            
            [freq,full,app.rms]=psd_core(mmm,NW,mr_choice,h_choice,app.amp,app.df);
            
            fprintf('\n Overall RMS = %10.3g  \n',app.rms);
            fprintf(' Three Sigma = %10.3g   \n\n',3*app.rms);
           
            freq=fix_size(freq);
            full=fix_size(full);
            
            app.power_spectral_density=[freq full]; 
            
            [~,ffmax]=find_max(app.power_spectral_density);
            
            fprintf('\n Peak occurs at %10.5g Hz \n\n',ffmax);
                
            nsz=length(full);
            
            app.Fourier_magnitude=zeros(nsz,2);
            
            app.Fourier_magnitude(:,1)=freq;
            
            for i=1:nsz
                app.Fourier_magnitude(i,2)=sqrt(full(i)*app.df);
            end
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value;              
            
            YS=app.s2;
            
            if(app.rms>=1000)
            
             t_string=...
                sprintf('Power Spectral Density  Overall Level = %7.4g %sRMS',app.rms,YS); 
             t_stringf=...
                sprintf('Fourier Magnitude  Overall Level = %7.4g %sRMS',app.rms,YS);
            else
                
             t_string=...
                sprintf('Power Spectral Density  Overall Level = %6.3g %sRMS',app.rms,YS);    
             t_stringf=...
                sprintf('Fourier Magnitude  Overall Level = %6.3g %sRMS',app.rms,YS);
            end            
            
            app.fig_num=2;
            xlab='Frequency (Hz)';
            app.x_label=xlab;
            try
                ylabf=sprintf('%s (%sRMS)',app.s1,app.s2);
                [app.fig_num,~]=plot_PSD_function(app.fig_num,xlab,ylabf,t_stringf,app.Fourier_magnitude,app.fmin,app.fmax);
            catch
            end
            
            
            app.power_spectral_density(1,:)=[];
             
                
            NNN=length(app.power_spectral_density(:,1));
             
            for i=NNN:-1:1
                app.power_spectral_density(i,2);
                if(app.power_spectral_density(i,2)<1.0e-40)
                    app.power_spectral_density(i,:)=[];
                end
            end
             
            md=6;
             
            ylab=sprintf('%s (%s^2/Hz)',app.s1,app.s2);
            app.y_label=ylab;
            [app.fig_num]=plot_loglog_function_md(app.fig_num,xlab,ylab,t_string,app.power_spectral_density,app.fmin,app.fmax,md);
             
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end        
        
            n=app.FunctionListBox.Value;
            if(contains(n,'Power'))
                data=app.power_spectral_density;
            else
                data=app.Fourier_magnitude;
            end
            
            try
                name=app.OutputArrayNameEditField.Value;
                assignin('base',name, data);
                msgbox('PSD saved');
            catch
                warndlg('Save failed');
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end
        
            FS = app.InputSpecificationArrayNameEditField.Value;
            try
                STHM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end  
        
            [~,srms] = calculate_PSD_slopes_no(STHM(:,1),STHM(:,2));

            ppp=app.power_spectral_density;
            qqq=STHM; 
            
   
            leg_b=sprintf('input %6.3g %sRMS',app.rms,app.s2);
            leg_a=sprintf('spec  %6.3g %sRMS',srms,app.s2);
            
            t_string='Power Spectral Density';
            
            pname='';
            nps=2;

            [app.fig_num]=plot_PSD_two_f(app.fig_num,app.x_label,app.y_label,t_string,qqq,ppp,leg_a,leg_b,app.fmin,app.fmax,pname,nps);
                
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end
           
            n=app.OutputOctaveFormatListBox.Value;
            if(strcmp(n,'1'))
                ioct=1;    
                so='one octave';
            end
            if(strcmp(n,'1/3'))
                ioct=2;
                so='1/3 octave';   
            end            
            if(strcmp(n,'1/6'))
                ioct=3;
                so='1/6 octave';
            end
            if(strcmp(n,'1/12'))
                ioct=4;
                so='1/12 octave';  
            end              
            if(strcmp(n,'1/24'))
                ioct=5;
                so='1/12 octave';  
            end
            
            [fl,fc,fu,~]=octaves_alt(ioct);
            
            ddf=0.05;
            
            fstart=app.power_spectral_density(1,1);
            fend=app.power_spectral_density(end,1);

            n=floor((fend-fstart)/ddf);

            upper=(n*ddf)+fstart;

            x=app.power_spectral_density(:,1);
            y=app.power_spectral_density(:,2);
    
            f = linspace(fstart,upper,n+1); 
            aamp = interp1(x,y,f);            

            
            [ff,ossum,opower_spectral_density,orms]=psd_oct_conversion(f,aamp,fl,fc,fu);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Format:  %9.5g '); 
            disp(' ');
            
            nbb=length(ff);
            
            for i=1:nbb
                fprintf(' %9.5g \t %9.5g \n',ff(i),ossum(i)); 
            end
   
            YS=app.s2;
            
            t_string=sprintf(' Power Spectral Density  %s   %6.3g %sRMS Overall ',so,orms,YS); 
            
            [app.fig_num,~]=plot_PSD_function(app.fig_num,app.x_label,app.y_label,t_string,opower_spectral_density,app.fmin,app.fmax);
                   
            md=6;

            ppp1=opower_spectral_density;
            ppp2=app.power_spectral_density;
            
            t_string='Power Spectral Density';
            leg1=sprintf('%s %6.3g %sRMS',so,orms,YS);
            leg2=sprintf('narrowband %6.3g %sRMS',app.rms,YS);
            
            [app.fig_num,~]=plot_loglog_function_md_two_h2(app.fig_num,app.x_label,...
                             app.y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);
       
            try
                name=app.OutputPSDNameEditField.Value;
                assignin('base',name, opower_spectral_density); 
            catch 
            end
                             
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button down function: CalculatePSDTab
        function CalculatePSDTabButtonDown(app, event)
            if(app.N>=1)
            else    
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end  
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               try
                    app.SelectInputArrayListBox.Value=name;
               catch
               end
            end    
            
        end

        % Button pushed function: ApplyTimeLimitsButton
        function ApplyTimeLimitsButtonPushed(app, event)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Segment'))
                tstart=app.StartTimesecEditField.Value;
                tend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);    

                Choice(app);

                msgbox('Go to Calculate PSD tab');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIvibrationdatapsdUIFigure = uifigure('Visible', 'off');
            app.UIvibrationdatapsdUIFigure.Name = 'UI vibrationdata psd';
            app.Label = uilabel(app.UIvibrationdatapsdUIFigure);
            app.Label.Text = 'vibrationdata_psd_ah   ver 2.0   by Tom Irvine   This script calculates the power spectral density of a time history.  ';
            app.TabGroup = uitabgroup(app.UIvibrationdatapsdUIFigure);
            app.DataEntryTab = uitab(app.TabGroup);
            app.DataEntryTab.Title = 'Data Entry';
            app.ReadDataButton = uibutton(app.DataEntryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.AmplitudeUnitEditFieldLabel = uilabel(app.DataEntryTab);
            app.AmplitudeUnitEditFieldLabel.Text = 'Amplitude Unit';
            app.AmplitudeUnitEditField = uieditfield(app.DataEntryTab, 'text');
            app.AmplitudeUnitEditField.Value = 'G';
            app.InputTypeListBoxLabel = uilabel(app.DataEntryTab);
            app.InputTypeListBoxLabel.Text = 'Input Type';
            app.InputTypeListBox = uilistbox(app.DataEntryTab);
            app.InputTypeListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'Force', 'Pressure', 'Other'};
            app.InputTypeListBox.Value = 'Acceleration';
            app.SignalProcessingListBoxLabel = uilabel(app.DataEntryTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.DataEntryTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.listbox_method = uilistbox(app.DataEntryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.DataEntryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.DataEntryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ApplyTimeLimitsButton = uibutton(app.DataEntryTab, 'push');
            app.ApplyTimeLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTimeLimitsButtonPushed, true);
            app.ApplyTimeLimitsButton.Text = 'Apply Time Limits';
            app.CalculatePSDTab = uitab(app.TabGroup);
            app.CalculatePSDTab.Title = 'Calculate PSD';
            app.CalculatePSDTab.ButtonDownFcn = createCallbackFcn(app, @CalculatePSDTabButtonDown, true);
            app.CalculateButton = uibutton(app.CalculatePSDTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.CalculatePSDTab);
            app.UITable.ColumnName = {'No. of Segments'; 'Samples/Segments'; 'Time/Segment (sec)'; 'df (Hz)'; 'dof'};
            app.UITable.RowName = {};
            app.SelectdofListBoxLabel = uilabel(app.CalculatePSDTab);
            app.SelectdofListBoxLabel.Text = 'Select dof';
            app.SelectdofListBox = uilistbox(app.CalculatePSDTab);
            app.ProcessingOptionsLabel = uilabel(app.CalculatePSDTab);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.MeanRemovalListBoxLabel = uilabel(app.CalculatePSDTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.CalculatePSDTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.PlotFrequenciesHzLabel = uilabel(app.CalculatePSDTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculatePSDTab, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculatePSDTab, 'numeric');
            app.MaxEditField.Value = 2000;
            app.WindowListBoxLabel = uilabel(app.CalculatePSDTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.CalculatePSDTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.Value = 'Rectangular';
            app.InputArrayEditFieldLabel = uilabel(app.CalculatePSDTab);
            app.InputArrayEditFieldLabel.Text = 'Input Array';
            app.InputArrayEditField = uieditfield(app.CalculatePSDTab, 'text');
            app.InputArrayEditField.Editable = 'off';
            app.ExportPSDTab = uitab(app.TabGroup);
            app.ExportPSDTab.Title = 'Export PSD';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportPSDTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportPSDTab, 'text');
            app.FunctionListBoxLabel = uilabel(app.ExportPSDTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportPSDTab);
            app.FunctionListBox.Items = {'Power Spectral Density', 'Fourier Magnitude'};
            app.FunctionListBox.Value = 'Power Spectral Density';
            app.SaveButton = uibutton(app.ExportPSDTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SuperimposeSpecTab = uitab(app.TabGroup);
            app.SuperimposeSpecTab.Title = 'Superimpose Spec';
            app.InputSpecificationArrayNameEditFieldLabel = uilabel(app.SuperimposeSpecTab);
            app.InputSpecificationArrayNameEditFieldLabel.Text = 'Input Specification Array Name';
            app.InputSpecificationArrayNameEditField = uieditfield(app.SuperimposeSpecTab, 'text');
            app.spec = uilabel(app.SuperimposeSpecTab);
            app.spec.Text = 'The input array must have two columns:  freq (Hz) & psd (unit^2/Hz)';
            app.PlotButton = uibutton(app.SuperimposeSpecTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ConverttoOctaveFormatTab = uitab(app.TabGroup);
            app.ConverttoOctaveFormatTab.Title = 'Convert to Octave Format';
            app.CalculateButton_2 = uibutton(app.ConverttoOctaveFormatTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.OutputOctaveFormatListBoxLabel = uilabel(app.ConverttoOctaveFormatTab);
            app.OutputOctaveFormatListBoxLabel.Text = 'Output Octave Format';
            app.OutputOctaveFormatListBox = uilistbox(app.ConverttoOctaveFormatTab);
            app.OutputOctaveFormatListBox.Items = {'1', '1/3', '1/6', '1/12', '1/24'};
            app.OutputOctaveFormatListBox.Value = '1';
            app.OutputPSDNameEditFieldLabel = uilabel(app.ConverttoOctaveFormatTab);
            app.OutputPSDNameEditFieldLabel.Text = 'Output PSD Name';
            app.OutputPSDNameEditField = uieditfield(app.ConverttoOctaveFormatTab, 'text');
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.UIvibrationdatapsdUIFigure);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec) & amplitude';
            app.ReturnButton = uibutton(app.UIvibrationdatapsdUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIvibrationdatapsdUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_psd_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIvibrationdatapsdUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIvibrationdatapsdUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIvibrationdatapsdUIFigure)
        end
    end
end