classdef vibrationdata_psd_rms_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel3               matlab.ui.container.Panel
        text15                 matlab.ui.control.Label
        listbox_psave          matlab.ui.control.ListBox
        uipanel2               matlab.ui.container.ButtonGroup
        listbox_output         matlab.ui.control.ListBox
        text_output_metric     matlab.ui.control.Label
        text13                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        edit_output_array      matlab.ui.control.EditField
        pushbutton_save        matlab.ui.control.Button
        text10                 matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        listbox_amp_unit       matlab.ui.control.ListBox
        pushbutton_return      matlab.ui.control.Button
        pushbutton_calculate   matlab.ui.control.Button
        text_dB_Margin         matlab.ui.control.Label
        edit_dB                matlab.ui.control.EditField
        edit_fmin              matlab.ui.control.EditField
        text8                  matlab.ui.control.Label
        text7                  matlab.ui.control.Label
        edit_fmax              matlab.ui.control.EditField
        listbox_add_dB         matlab.ui.control.ListBox
        text5                  matlab.ui.control.Label
        edit_input_array       matlab.ui.control.EditField
        text_input_array_name  matlab.ui.control.Label
        text3                  matlab.ui.control.Label
        listbox_type           matlab.ui.control.ListBox
        text2                  matlab.ui.control.Label
        listbox_method         matlab.ui.control.ListBox
        text1                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit_ylabel_input_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_ylabel_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function dB_change(app)

            Items = char(app.listbox_add_dB.Items);
            im=app.listbox_add_dB.Value;
            [n]=find_index(Items,im);

            if(n==1)
                app.text_dB_Margin.Visible='off';
                app.edit_dB.Visible='off';
            else
                app.text_dB_Margin.Visible='on';
                app.edit_dB.Visible='on';
            end

        end

        function L_type(app)
            
            app.listbox_output.Visible='off';
            app.text_output_metric.Visible='off';

            Items = char(app.listbox_type.Items);
            im=app.listbox_type.Value;
            
            [n]=find_index(Items,im);
            
            if(n==1)
                string_unit{1}=sprintf('G, in/sec, in');
                string_unit{2}=sprintf('G, cm/sec, mm');
                string_unit{3}=sprintf('m/sec^2, cm/sec, mm');
            
                app.listbox_output.Visible='on';
                app.text_output_metric.Visible='on';
            end
            if(n==2)
                string_unit{1}=sprintf('in/sec');
                string_unit{2}=sprintf('m/sec');
                string_unit{3}=sprintf('cm/sec');
            end
            if(n==3)
                string_unit{1}=sprintf('in');
                string_unit{2}=sprintf('m');
                string_unit{3}=sprintf('mm');
            end
            if(n==4)
                string_unit{1}=sprintf('lbf');
                string_unit{2}=sprintf('N');
            end
            if(n==5 || n==6)
                string_unit{1}=sprintf('psi');
                string_unit{2}=sprintf('ksi');
                string_unit{3}=sprintf('Pa');
                string_unit{4}=sprintf('KPa');
                string_unit{5}=sprintf('MPa');
            end
            if(n==7)
                string_unit{1}=sprintf('unit');
            end
            
            app.listbox_amp_unit.Items=string_unit;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_psd_rms_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_psd_rms (see VARARGIN)
            
            % Choose default command line output for vibrationdata_psd_rms
            handles.output = hObject;
            
            app.listbox_type.Value='Acceleration';
           
            L_type(app);

            dB_change(app);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_add_dB
        function listbox_add_dB_Callback(app, event)
            dB_change(app);
        end

        % Value changed function: listbox_method
        function listbox_method_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_method (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_method contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_method
            % set(handles.pushbutton_calculate,'Enable','off');
            
            n=get(hObject,'Value');
            
            set(handles.edit_output_array,'Enable','off');
            
            set(handles.pushbutton_save,'Enable','off');
            
            set(handles.edit_input_array,'String',' ');
            
            set(handles.text_input_array_name,'Visible','on');
            set(handles.edit_input_array,'Visible','on');
            
            if(n==1)
               set(handles.edit_input_array,'enable','on')
            else
               set(handles.edit_input_array,'enable','off')
               set(handles.text_input_array_name,'Visible','off');
               set(handles.edit_input_array,'Visible','off');
            
               [filename, pathname] = uigetfile('*.*');
               filename = fullfile(pathname, filename);
               fid = fopen(filename,'r');
               THM = fscanf(fid,'%g %g',[2 inf]);
               THM=THM';
            
            
               if(isempty(THM))
                   warndlg('Ref 1:  Input Array is empty ');
                   return;
               end
            
               setappdata(0,'THM',THM);
            
               set(handles.pushbutton_calculate,'Enable','on');
            end
        end

        % Value changed function: listbox_type
        function listbox_type_Callback(app, event)
            
            L_type(app);
           
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (se
            
            
            %%%%%%%%%%%
            
            try
            
                Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
                NFigures = length( Figures );
                for nFigures = 1 : NFigures
                    close( Figures( nFigures ) );
                end
            catch
            end
            
            %%%%%%%%%%%
            
            fprintf('\n * * * * * * * \n');
            
            psave=get(handles.listbox_psave,'Value');
            
            k=get(handles.listbox_method,'Value');
            
            set(handles.edit_output_array,'Enable','on');
            set(handles.pushbutton_save,'Enable','on')
            
            
            try
            
                if(k==1)
                    FS=get(handles.edit_input_array,'String');
                    THM=evalin('base',FS);
                else
                    THM=getappdata(0,'THM');
                end
            
            catch
            
                warndlg('Input array not found');
                return;
            
            end
            
            if(isempty(THM))
                warndlg('Input array is empty');
                return;
            end
            
            n=length(THM(:,1));
            
            if(THM(1,1)<1.0e-04)
                THM(1,1)=1.0e-04;
            end
            
            
            sminf=get(handles.edit_fmin,'String');
            smaxf=get(handles.edit_fmax,'String');
            
            if isempty(sminf)
                string=sprintf('%8.4g',THM(1,1));
                set(handles.edit_fmin,'String',string);
            end
            
            if isempty(smaxf)
                string=sprintf('%8.4g',THM(n,1));
                set(handles.edit_fmax,'String',string);
            end
            
            
            f=THM(:,1);
            ddd=diff(f);
            maxd=max(ddd);
            mind=min(ddd);
            ratio=(maxd-mind)/maxd;
            
            if(ratio<0.02)
            
                fmin=str2double(get(handles.edit_fmin,'String'));
                fmax=str2double(get(handles.edit_fmax,'String'));
            
                [~,i]=min(abs(f-fmin));
                [~,j]=min(abs(f-fmax));
            
                if(i>=2 && f(i)>fmin)
                    i=i-1;
                end
            
                THM=THM(i:j,:);
            end
            
            
            md=get(handles.listbox_add_dB,'Value');
            
            if(md==2)
               dB=str2num(get(handles.edit_dB,'String'));
               scale=10^(dB/10);
            %
               for i=1:n
                   THM(i,2)=THM(i,2)*scale;
               end
            %
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [aslope,rms] = calculate_PSD_slopes_no(THM(:,1),THM(:,2));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            qrms=rms;
            
            if(length(THM(:,1))<=60)
            
                disp(' ');
            
                disp('   Freq      Accel                  ');
                disp('   (Hz)     (G^2/Hz)   Slope    dB/oct');
            
                fprintf(' %8.4g  %8.4g \n',THM(1,1),THM(1,2));
            
                for i=2:n
                    try
                        dbo=10*aslope(i-1)*log10(2);
                        fprintf(' %8.4g  %8.4g  %7.3g  %7.3g \n',THM(i,1),THM(i,2),aslope(i-1),dbo);
                    catch
                        break;
                    end
                end
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            
            n=get(handles.listbox_type,'Value');
            
            m=get(handles.listbox_amp_unit,'Value');
            
            
            if(n==1)
                if(m==1)
                    YS=sprintf('G');
                    YSV=sprintf('in/sec');
                    YSD=sprintf('in');
                end
                if(m==2)
                    YS=sprintf('G');
                    YSV=sprintf('cm/sec');
                    YSD=sprintf('mm');
                end
                if(m==3)
                    YS=sprintf('m/sec^2');
                    YSV=sprintf('cm/sec');
                    YSD=sprintf('mm');
                end
            end
            if(n==2)
                if(m==1)
                    YS=sprintf('in/sec');
                end
                if(m==2)
                    YS=sprintf('m/sec');
                end
                if(m==3)
                    YS=sprintf('cm/sec');
                end
            end
            if(n==3)
                if(m==1)
                    YS=sprintf('in');
                end
                if(m==2)
                    YS=sprintf('m');
                end
                if(m==3)
                    YS=sprintf('mm');
                end
            end
            if(n==4)
                if(m==1)
                    YS=sprintf('lbf');
                end
                if(m==2)
                    YS=sprintf('N');
                end
            end
            if(n==5 || n==6)
                if(m==1)
                    YS=sprintf('psi');
                end
                if(m==2)
                    YS=sprintf('ksi');
                end
                if(m==3)
                    YS=sprintf('Pa');
                end
                if(m==4)
                    YS=sprintf('KPa');
                end
                if(m==5)
                    YS=sprintf('MPa');
                end
            end
            if(n==7)
                YS=sprintf('unit');
            end


            k = strfind(YS,'/');
            
            if(n==1)
                out2=sprintf('Accel (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Accel ((%s)^2/Hz)',YS);
                end
            end
            if(n==2)
                out2=sprintf('Vel ((%s)^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Vel ((%s)^2/Hz)',YS);
                end
            end
            if(n==3)
                out2=sprintf('Disp (%s^2/Hz)',YS);
            end
            if(n==4)
                out2=sprintf('Force (%s^2/Hz)',YS);
            end
            if(n==5)
                out2=sprintf('Pressure (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Pressure ((%s)^2/Hz)',YS);
                end
            end
            if(n==6)
                out2=sprintf('Stress (%s^2/Hz)',YS);
                if( k>=1)
                  out2=sprintf('Stress ((%s)^2/Hz)',YS);
                end
            end
            if(n==7)
                out2=sprintf('(%s^2/Hz)',YS);
            end
            
            clear length;
            f=THM(:,1);
            a=THM(:,2);
            nA=length(a);
            
            dpsd=zeros(nA,2);
            vpsd=zeros(nA,2);
            
            if(n==1) % integerate to velocity & displacement
            %
                set(handles.listbox_output,'Visible','on');
                set(handles.text_output_metric,'Visible','on');
            %
              omega=2*pi*f;
            %
              v=zeros(nA,1);
              d=zeros(nA,1);
            %
              for i=1:nA
                v(i)=a(i)/omega(i)^2;
                d(i)=v(i)/omega(i)^2;
              end
            %
              if(f(1)<=1.0e-10)
                 f(1)=[];
                 v(1)=[];
                 d(1)=[];
              end
            %
              if(m==1)
                  v=v*386^2;
                  d=d*386^2;
              end
              if(m==2)
                  v=v*(9.81*100)^2;
                  d=d*(9.81*1000)^2;
              end
              if(m==3)
                  v=v*(100)^2;
                  d=d*(1000)^2;
              end
            %
              d=fix_size(d);
              v=fix_size(v);
            %
              dpsd=[f d];
              vpsd=[f v];
            %
                setappdata(0,'vpsd',vpsd);
                setappdata(0,'dpsd',dpsd);
            %


              [~,vrms]=calculate_PSD_slopes_no(f,v);
              [~,drms]=calculate_PSD_slopes_no(f,d);
            %
                  disp(' ');
                  disp(' Overall levels ');
                  disp(' ');
            %
               if(m<=2)
                  out12=sprintf(' Acceleration = %6.3g GRMS ',rms);
                  out13=sprintf('              = %6.3g G (3-sigma) \n',3*rms);
               else
                  out12=sprintf(' Acceleration = %6.3g m/sec^2 RMS ',rms);
                  out13=sprintf('              = %6.3g m/sec^2 (3-sigma) \n',3*rms);
               end
            %
               if(m==1)
                  out22=sprintf('     Velocity = %6.3g in/sec RMS ',vrms);
                  out23=sprintf('              = %6.3g in/sec (3-sigma) \n',3*vrms);
                  out32=sprintf(' Displacement = %6.3g inch RMS ',drms);
                  out33=sprintf('              = %6.3g inch (3-sigma) ',3*drms);
               else
                  out22=sprintf('     Velocity = %6.3g cm/sec RMS ',vrms);
                  out23=sprintf('              = %6.3g cm/sec (3-sigma) \n',3*vrms);
                  out32=sprintf(' Displacement = %6.3g mm RMS ',drms);
                  out33=sprintf('              = %6.3g mm (3-sigma) ',3*drms);
               end
            %
                  disp(out12);
                  disp(out13);
                  disp(out22);
                  disp(out23);
                  disp(out32);
                  disp(out33);
            %
            else
                fprintf('\n Power Spectral Density \n \n %6.3g %sRMS Overall \n',qrms,YS);
            end
            
            fig_num=1;
            xlab='Frequency (Hz)';
            
            try
                fmin=str2num(get(handles.edit_fmin,'String'));
            catch
                fmin=f(1);
            end
            
            try
                fmax=str2num(get(handles.edit_fmax,'String'));
            catch
                fmax=f(end);
            end
            
            power_spectral_density=THM;
            
            setappdata(0,'PSD',power_spectral_density);
            
            [crms]=cumulative_rms(power_spectral_density);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(n==1 && m<=2)
                ylab=sprintf('%sRMS',YS);
            else
                ylab=sprintf('%s RMS',YS);
            end
            
            t_string=...
                sprintf(' Cumulative RMS');
            [fig_num]=plot_cumulative_RMS(fig_num,xlab,ylab,t_string,crms,fmin,fmax);
            
            
            if(n==1)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=sprintf('Disp (%s^2/Hz)',YSD);
            
                t_string=sprintf('Displacement Power Spectral Density %6.3g %s RMS Overall ',drms,YSD);
                [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,dpsd,fmin,fmax);
            
                if(psave==1)
            
                    disp(' ');
                    disp(' Plot files');
                    disp(' ');
            
                    pname='displacement_psd';
            
                    out1=sprintf('   %s.png',pname);
                    disp(out1);
            
                    set(gca,'Fontsize',12);
                    print(h,pname,'-dpng','-r300');
            
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=sprintf('Vel ((%s)^2/Hz)',YSV);
            
                t_string=sprintf('Velocity Power Spectral Density %6.3g %s RMS Overall ',vrms,YSV);
                [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,vpsd,fmin,fmax);
            
                if(psave==1)
            
                    pname='velocity_psd';
            
                    out1=sprintf('   %s.png',pname);
                    disp(out1);
            
                    set(gca,'Fontsize',12);
                    print(h,pname,'-dpng','-r300');
            
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                ylab=out2;
                if(n==1 && m<=2)
                    t_string=sprintf('Acceleration Power Spectral Density %6.3g %sRMS Overall ',qrms,YS);
                else
                    t_string=sprintf('Acceleration Power Spectral Density %6.3g %s RMS Overall ',qrms,YS);
                end
                [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,fmin,fmax);
            
            else
            
                ylab=out2;
                t_string=sprintf('Power Spectral Density %6.3g %s RMS Overall ',qrms,YS);
                [fig_num,h]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,fmin,fmax);
            
            end
            
            if(psave==1)
            
                pname='accel_psd';
            
                out1=sprintf('   %s.png',pname);
                disp(out1);
            
                set(gca,'Fontsize',12);
                print(h,pname,'-dpng','-r300');
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            f=THM(:,1);
            a=THM(:,2);
            %
            
            if(f(1)<0.001)
                f(1)=0.001;
            end
            
            df=0.2;
            
            [s,~]=calculate_PSD_slopes_no(f,a);
            
            [fi,ai]=interpolate_PSD(f,a,s,df);
            
            [~,ii]=min(abs(fi-fmin));
            [~,jj]=min(abs(fi-fmax));
            
            fi=fi(ii:jj);
            ai=ai(ii:jj);
            
            [EP,vo,m0,m1,m2,m4,alpha2,e]=spectal_moments_alt(fi,ai,df);
            
            disp(' Spectral Moments');
            disp(' ');
            
            fprintf(' m0=%8.4g \t m1=%8.4g \t m2=%8.4g  \t m4=%8.4g \n\n',m0,m1,m2,m4);
            
            fprintf(' alpha2=%8.4g \t e=%8.4g spectral width \n\n',alpha2,e);
            
            fprintf(' Rate of Up-zero crossings = %7.4g Hz \n',vo);
            fprintf('             Rate of peaks = %7.4g Hz \n\n',EP);
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            n=get(handles.listbox_type,'Value');
            
            if(n==1)
            %
                p=get(handles.listbox_output,'Value');
            %
                if(p==1) % accel
                    data=getappdata(0,'PSD');
                end
                if(p==2) % vel
                    data=getappdata(0,'vpsd');
                end
                if(p==3) % disp
                    data=getappdata(0,'dpsd');
                end
            %
            else
            %
                data=getappdata(0,'PSD');
            %
            end
            
            output_name=get(handles.edit_output_array,'String');
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_psd_rms';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.uipanel1);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_method_Callback, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Input Type';
            app.listbox_type = uilistbox(app.uipanel1);
            app.listbox_type.Items = {'Acceleration', 'Velocity', 'Displacement', 'Force', 'Pressure', 'Stress', 'Other'};
            app.listbox_type.ValueChangedFcn = createCallbackFcn(app, @listbox_type_Callback, true);
            app.listbox_type.Tag = 'listbox_type';
            app.listbox_type.Value = 'Acceleration';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Amplitude Unit';
            app.text_input_array_name = uilabel(app.uipanel1);
            app.text_input_array_name.Tag = 'text_input_array_name';
            app.text_input_array_name.Text = 'Input Array Name';
            app.edit_input_array = uieditfield(app.uipanel1, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Add dB';
            app.listbox_add_dB = uilistbox(app.uipanel1);
            app.listbox_add_dB.Items = {'no', 'yes'};
            app.listbox_add_dB.ValueChangedFcn = createCallbackFcn(app, @listbox_add_dB_Callback, true);
            app.listbox_add_dB.Tag = 'listbox_add_dB';
            app.listbox_add_dB.Value = 'no';
            app.edit_fmax = uieditfield(app.uipanel1, 'text');
            app.edit_fmax.Tag = 'edit_fmax';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum Plot Freq (Hz)';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Minimum Plot Freq (Hz)';
            app.edit_fmin = uieditfield(app.uipanel1, 'text');
            app.edit_fmin.Tag = 'edit_fmin';
            app.edit_dB = uieditfield(app.uipanel1, 'text');
            app.edit_dB.Tag = 'edit_dB';
            app.text_dB_Margin = uilabel(app.uipanel1);
            app.text_dB_Margin.Tag = 'text_dB_Margin';
            app.text_dB_Margin.Text = 'dB Margin';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.listbox_amp_unit = uilistbox(app.uipanel1);
            app.listbox_amp_unit.Items = {'G, in/sec, in', 'G, cm/sec, mm', 'm/sec^2, cm/sec, mm'};
            app.listbox_amp_unit.Tag = 'listbox_amp_unit';
            app.listbox_amp_unit.Value = 'G, in/sec, in';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'vibrationdata_psd_rms_App   ver 3.0  by Tom Irvine';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'This script calculates the overall level of a power spectral density.  The input array must have two columns: freq(Hz) & amp(unit^2/Hz)';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.pushbutton_save = uibutton(app.uipanel2, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.edit_output_array = uieditfield(app.uipanel2, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Output Array Name';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Save PSD to Matlab Workspace';
            app.text_output_metric = uilabel(app.uipanel2);
            app.text_output_metric.Tag = 'text_output_metric';
            app.text_output_metric.Text = 'Select Output Metric';
            app.listbox_output = uilistbox(app.uipanel2);
            app.listbox_output.Items = {'Acceleration', 'Velocity', 'Displacement'};
            app.listbox_output.Tag = 'listbox_output';
            app.listbox_output.Value = 'Acceleration';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.listbox_psave = uilistbox(app.uipanel3);
            app.listbox_psave.Items = {'yes', 'no'};
            app.listbox_psave.Tag = 'listbox_psave';
            app.listbox_psave.Value = 'yes';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Export plot to png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_psd_rms_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_psd_rms_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end