classdef vibrationdata_psd_sdof_base_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataPSDSDOFBaseUIFigure  matlab.ui.Figure
        InputArrayNameEditField         matlab.ui.control.EditField
        InputArrayNameEditFieldLabel    matlab.ui.control.Label
        DurationonlyaffectsmaximumexpectedpeakFDSonlyLabel  matlab.ui.control.Label
        AmplitudeUnitsListBox           matlab.ui.control.ListBox
        AmplitudeUnitsListBoxLabel      matlab.ui.control.Label
        OutputFrequencySpacingListBox   matlab.ui.control.ListBox
        OutputFrequencySpacingListBoxLabel  matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        PlotFrequenciesHzLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GivenfnTab                      matlab.ui.container.Tab
        SteinbergFatigueDamageButton    matlab.ui.control.Button
        RelativeFatigueDamageButton     matlab.ui.control.Button
        NaturalFrequencyHzEditField     matlab.ui.control.NumericEditField
        NaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        ExporttoMatlabWorkspaceListBox  matlab.ui.control.ListBox
        ExporttoMatlabWorkspaceListBoxLabel  matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        CaculateButton                  matlab.ui.control.Button
        VRSTab                          matlab.ui.container.Tab
        VRSTypeListBox_2                matlab.ui.control.ListBox
        VRSOutputArrayNameEditField     matlab.ui.control.EditField
        VRSOutputArrayNameEditFieldLabel  matlab.ui.control.Label
        VRSTypeListBox                  matlab.ui.control.ListBox
        VRSTypeListBoxLabel             matlab.ui.control.Label
        SaveVRSButton                   matlab.ui.control.Button
        SaveFDSButton                   matlab.ui.control.Button
        FDSOutputArrayNameEditField     matlab.ui.control.EditField
        FDSOutputArrayNameEditFieldLabel  matlab.ui.control.Label
        FatigueTypeListBox              matlab.ui.control.ListBox
        FatigueTypeListBoxLabel         matlab.ui.control.Label
        FatigueExponentEditField        matlab.ui.control.NumericEditField
        FatigueExponentEditFieldLabel   matlab.ui.control.Label
        CalculateFunctionsListBox       matlab.ui.control.ListBox
        CalculateFunctionsListBoxLabel  matlab.ui.control.Label
        CaculateVRSButton               matlab.ui.control.Button
        QEditField                      matlab.ui.control.NumericEditField
        QEditFieldLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DurationsecEditField            matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        vibrationdata_psd_sdof_base_aver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM  %
        f    %
        a    %
        fmin %
        fmax %
        damp %
        s    %
        input_rms %
        ndf  %
        ffn  %
        fi   %
        ai   %
        T    %
        Q    %
        iu   %
        ivrs %
        df   %
        fds  %
        accel_response_psd %
        rd_response_psd    %
        accel_power_trans  %
        accel_vrs_1_sigma  %
        accel_vrs_3_sigma  %
        accel_vrs_peak     %
 
        pv_vrs_1_sigma  %
        pv_vrs_3_sigma  %
        pv_vrs_peak     %
 
        rd_vrs_1_sigma  %
        rd_vrs_3_sigma  %
        rd_vrs_peak     %
        
        spec_minf %
        spec_maxf %
        
        vtypes %
        vtype  %

        ftypes %
        ftype  %        
    end
    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end    
            
            app.RelativeFatigueDamageButton.Enable='off';
            app.SteinbergFatigueDamageButton.Enable='off';
        end
        
        function ChangeVRS_aux(app)
            app.vtypes=app.VRSTypeListBox.Value;
            
            if(contains(app.vtypes,'Acceleration'))
                app.vtype=1;
            end
            if(contains(app.vtypes,'Velocity'))
                app.vtype=2;
            end
            if(contains(app.vtypes,'Disp'))
                app.vtype=3;
            end   
            
            app.ftypes=app.FatigueTypeListBox.Value;
            if(contains(app.ftypes,'Acceleration'))
                app.ftype=1;
            end
            if(contains(app.ftypes,'Velocity'))
                app.ftype=2;
            end
            if(contains(app.ftypes,'Disp'))
                app.ftype=3;
            end   
        end
        
        function ChangeVRS(app)
            y=strtrim(app.CalculateFunctionsListBox.Value);
            if(strcmp(y,'VRS'))
                app.ivrs=1;
                app.FatigueExponentEditField.Visible='off';
                app.FatigueExponentEditFieldLabel.Visible='off';
                app.FatigueTypeListBox.Visible='off';
                app.FatigueTypeListBoxLabel.Visible='off';             
            else    
                app.ivrs=2;
                app.FatigueExponentEditField.Visible='on';
                app.FatigueExponentEditFieldLabel.Visible='on';
                app.FatigueTypeListBox.Visible='on';
                app.FatigueTypeListBoxLabel.Visible='on';             
            end
            
            app.SaveFDSButton.Visible='off';
            app.FDSOutputArrayNameEditField.Visible='off';
            app.FDSOutputArrayNameEditFieldLabel.Visible='off';
            
            app.SaveVRSButton.Visible='off';
            app.VRSTypeListBox.Visible='off';
            app.VRSTypeListBoxLabel.Visible='off';
            app.VRSTypeListBox_2.Visible='off';
            app.VRSOutputArrayNameEditField.Visible='off';
            app.VRSOutputArrayNameEditFieldLabel.Visible='off';       
            
            ChangeVRS_aux(app);          
            
        end
        function ReadData(app)
        
            ChangeVRS(app);
        
            FS = app.InputArrayNameEditField.Value;
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end     
            
            if(app.THM(:,1)<1.0e-20)
                app.THM(1,:)=[];
            end   
            
            app.f=app.THM(:,1);
            app.a=app.THM(:,2);
            
            app.spec_minf=app.THM(1,1); 
            app.spec_maxf=app.THM(end,1);  
            
            no=app.OutputFrequencySpacingListBox.Value;
            
            if(contains(no,'0.5'))
                app.ndf=1;    
            end
            if(contains(no,'1/3'))
                app.ndf=2;                 
            end  
            if(contains(no,'1/6'))
                app.ndf=3;                 
            end
            if(contains(no,'1/12'))
                app.ndf=4;                 
            end 
            if(contains(no,'1/24'))
                app.ndf=5;                 
            end 
            
            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value;             
            app.Q=app.QEditField.Value;  
            app.damp=1/(2*app.Q);  
            
            [app.s,app.input_rms] = calculate_PSD_slopes(app.THM(:,1),app.THM(:,2));   
            
            app.df=0.5;
            [app.fi,app.ai]=interpolate_PSD(app.f,app.a,app.s,app.df);
            
            if(app.ndf==1)
                app.ffn=app.fi;
            else
                if(app.ndf==2)
                    oct=1/3;
                end
                if(app.ndf==3)
                    oct=1/6;        
                end    
                if(app.ndf==4)
                    oct=1/12;        
                end
                if(app.ndf==5)
                    oct=1/24;        
                end
                
                app.ffn(1)=app.fi(1);
                f2=app.fi(end);
                j=2;
            
                while(1)
                    app.ffn(j)=app.ffn(j-1)*2^(oct);
                    if(app.ffn(j)>f2)
                        app.ffn(j)=f2;
                        break;
                    end
                    j=j+1;
                end    
            end           
            
            ss=strtrim(app.AmplitudeUnitsListBox.Value);
            
            if(strcmp(ss,'G, in/sec, in'))
                app.iu=1;
            end
            if(strcmp(ss,'G, m/sec, mm'))
                app.iu=2;
            end   
            if(strcmp(ss,'m/sec^2, m/sec, mm'))
                app.iu=3;
            end     
            
            app.T=app.DurationsecEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            ChangeVRS(app);
            app.ResultsTextArea.Enable='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CaculateButton
        function CaculateButtonPushed(app, event)
            ClosePlots(app);
            ReadData(app);
            
            fn=app.NaturalFrequencyHzEditField.Value;            

            if(fn<=0)
                warndlg('Natural frequency must be > 0');
                return;
            end
            
            natural_frequency=fn;
            omegan=2*pi*fn;      
            
            [a_vrs,rd_vrs,trans,opsd,rd_psd]=...
                             vibrationdata_sdof_ran_engine_function_ard(app.fi,app.ai,app.damp,natural_frequency);
                    
            if(app.iu==1)
                rd_vrs=rd_vrs*386;
                rd_psd=rd_psd*386^2;
            end
            if(app.iu==2)
                rd_vrs=rd_vrs*9.81*1000;
                rd_psd=rd_psd*(9.81*1000)^2;
            end
            if(app.iu==3)
                rd_vrs=rd_vrs*1000;
                rd_psd=rd_psd*1000^2;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ax=0.5;
            [ms]=risk_overshoot(fn,app.T,ax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.ndf==1)   
                [EP,vo,~,~,~,~]=spectal_moments(app.fi,opsd,app.df);    
            else
                [EP,vo,~,~,~,~]=spectal_moments_oct(app.fi,opsd);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string_acc=sprintf(' SDOF Acceleration Response ');
            string_pv=sprintf('\n\n SDOF Pseudo Velocity Response ');
            string_rd=sprintf('\n\n SDOF Relative Displacement Response ');
            
            if(app.iu<=2)
                out1=sprintf('\n\n   = %8.3g GRMS',a_vrs);
                out2=sprintf('\n   = %8.3g G 3-sigma',3.*a_vrs);
                out3=sprintf('\n   = %8.3g G %4.3g-sigma (maximum expected)',ms*a_vrs,ms);
            else
                out1=sprintf('\n\n   = %8.3g (m/sec^2)RMS',a_vrs);
                out2=sprintf('\n   = %8.3g (m/sec^2) 3-sigma',3.*a_vrs);
                out3=sprintf('\n   = %8.3g (m/sec^2) %4.3g-sigma (maximum expected)',ms*a_vrs,ms);    
            end    
            
            if(app.iu==1)
                out4=sprintf('\n\n   = %8.3g inch/sec RMS',omegan*rd_vrs);
                out5=sprintf('\n   = %8.3g inch/sec 3-sigma',omegan*3.*rd_vrs);
                out6=sprintf('\n   = %8.3g inch/sec %4.3g-sigma (maximum expected)',omegan*ms*rd_vrs,ms);     
                
                out7=sprintf('\n\n   = %8.3g inch RMS',rd_vrs);
                out8=sprintf('\n   = %8.3g inch 3-sigma',3.*rd_vrs);
                out9=sprintf('\n   = %8.3g inch %4.3g-sigma (maximum expected)',ms*rd_vrs,ms);   
            else
                out4=sprintf('\n\n   = %8.3g mm/sec RMS',omegan*rd_vrs);
                out5=sprintf('\n   = %8.3g mm/sec 3-sigma',omegan*3.*rd_vrs);
                out6=sprintf('\n   = %8.3g mm/sec %4.3g-sigma (maximum expected)',omegan*ms*rd_vrs,ms); 
                
                out7=sprintf('\n\n   = %8.3g mm RMS',rd_vrs);
                out8=sprintf('\n   = %8.3g mm 3-sigma',3.*rd_vrs);
                out9=sprintf('\n   = %8.3g mm %4.3g-sigma (maximum expected)',ms*rd_vrs,ms);     
            end
            
            out90=sprintf('\n\n Rate of Up-zero crossings = %7.4g Hz',vo);
            out91=sprintf('\n             Rate of peaks = %7.4g Hz',EP);
            
            string_big=strcat(string_acc,out1);
            string_big=strcat(string_big,out2);
            string_big=strcat(string_big,out3);
            
            string_big=strcat(string_big,string_pv);
            string_big=strcat(string_big,out4);
            string_big=strcat(string_big,out5);
            string_big=strcat(string_big,out6);
            
            string_big=strcat(string_big,string_rd);
            string_big=strcat(string_big,out7);
            string_big=strcat(string_big,out8);
            string_big=strcat(string_big,out9);
            
            string_big=strcat(string_big,out90);
            string_big=strcat(string_big,out91);
            
            app.ResultsTextArea.Value=string_big;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            app.fi=fix_size(app.fi);
            opsd=fix_size(opsd);
            trans=fix_size(trans);
            
            rd_psd=fix_size(rd_psd);
            %
            ppp=[app.fi opsd];
            qqq=app.THM;
            %
            app.accel_response_psd=[app.fi opsd];
            app.accel_power_trans=[app.fi trans];
            app.rd_response_psd=[app.fi rd_psd];
            %              
            x_label=sprintf(' Frequency (Hz)');
            %
            if(app.iu<=2)
               y_label=sprintf(' Accel (G^2/Hz)');
               leg_a=sprintf('Response %5.3g GRMS',a_vrs);
               leg_b=sprintf('Input %5.3g GRMS',app.input_rms);   
            else
               y_label=sprintf(' Accel ((m/sec^2)^2/Hz)');  
               leg_a=sprintf('Response %5.3g (m/sec^2)RMS',a_vrs);
               leg_b=sprintf('Input %5.3g (m/sec^2)RMS',app.input_rms);      
            end
             
            t_string = sprintf(' Power Spectral Density   fn=%g Hz  Q=%g  ',natural_frequency,app.Q);   
            %
            [fig_num,~]=plot_PSD_two_sdof_ran_f(fig_num,x_label,y_label,t_string,ppp,qqq,leg_a,leg_b,app.fmin,app.fmax); 
            %
            
            ppp=[app.fi trans];  
            x_label=sprintf(' Frequency (Hz)');
            if(app.iu<=2)
                y_label=sprintf(' Trans (G^2/G^2)'); 
            else
                y_label=sprintf(' Trans ((m/sec^2)^2/(m/sec^2)^2)');     
            end    
            t_string = sprintf(' Power Transmissibility   fn=%g Hz  Q=%g',natural_frequency,app.Q);   
            [fig_num,~]=plot_PSD_function(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax);
            %

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            [~,rd_rms] = calculate_PSD_slopes(app.rd_response_psd(:,1),app.rd_response_psd(:,2));
            
            if(app.iu<=1)
                y_label=sprintf('Rel Disp (inch^2/Hz)'); 
                t_string = sprintf(' Relative Displacement PSD   fn=%g Hz  Q=%g \n Overall Level = %7.3g in RMS',natural_frequency,app.Q,rd_rms);   
            else
                y_label=sprintf('Rel Disp (mm^2/Hz)');     
                t_string = sprintf(' Relative Displacement PSD   fn=%g Hz  Q=%g \n Overall Level = %7.3g mm RMS',natural_frequency,app.Q,rd_rms);       
            end 
            
            % [fig_num,h]=plot_PSD_function(fig_num,x_label,y_label,t_string,rd_response_psd,fmin,fmax);
            
            
            ppp=app.rd_response_psd;
            md=5;
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                         
            %
            app.ResultsTextArea.Enable='on';
            app.SaveButton.Enable='on';
            
        end

        % Button pushed function: CaculateVRSButton
        function CaculateVRSButtonPushed(app, event)
            ClosePlots(app);
            ReadData(app);
            ChangeVRS(app);
            
            idc=2;
            bex=0;
            
            if(app.ivrs==2)
                bex=app.FatigueExponentEditField.Value;
                
                if(isempty(bex))
                    warndlg('Enter fatigue exponent');
                    return;
                end
                
                idc=1;
            end         
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                     
            [app.ffn,a_vrs,pv_vrs,rd_vrs,damage]=...
                         vibrationdata_fds_vrs_engine_fn(app.fi,app.ai,app.damp,app.df,app.iu,idc,app.ftype,bex,app.T,app.ffn);         
                     
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.ffn=fix_size(app.ffn);
            a_vrs=fix_size(a_vrs);
            pv_vrs=fix_size(pv_vrs);
            rd_vrs=fix_size(rd_vrs);
            damage=fix_size(damage);
            
            j=1;
            
            L=length(app.ffn);
            
            for i=1:L
            %    
                if(app.ffn(i)>=app.spec_minf && app.ffn(i)<=app.spec_maxf)
            %
                    ax=0.5;
                    [ms]=risk_overshoot(app.ffn(i),app.T,ax);
            %
                    app.accel_vrs_peak(j,:)=[app.ffn(i)  ms*a_vrs(i)];
                    app.pv_vrs_peak(j,:)=[app.ffn(i) ms*pv_vrs(i)];        
                    app.rd_vrs_peak(j,:)=[app.ffn(i) ms*rd_vrs(i)];
                    j=j+1;
                end   
            %       
            end
            
            fig_num=1;
            power_spectral_density=[app.f app.a];
            xlab='Frequency (Hz)';
            if(app.iu<=1)
                ylab='Accel (G^2/Hz)';
                YS='G';
            else
                ylab='Accel ((m/sec^2)^2/Hz)';
                YS='(m/sec^2)^2';
            end
            
            t_string=...
                sprintf(' Power Spectral Density  Overall Level = %6.3g %sRMS ',app.input_rms,YS); 
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,app.fmin,app.fmax);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
                
            app.accel_vrs_1_sigma=[app.ffn a_vrs];
            app.accel_vrs_3_sigma=[app.ffn 3*a_vrs];
            
               app.pv_vrs_1_sigma=[app.ffn pv_vrs];
               app.pv_vrs_3_sigma=[app.ffn 3*pv_vrs];
               
               app.rd_vrs_1_sigma=[app.ffn rd_vrs];
               app.rd_vrs_3_sigma=[app.ffn 3*rd_vrs];
               
            if(app.ivrs==2)   
                app.fds=[app.ffn damage];
            end
                
            %%%%%%%%%%%%%%%%%%%%
            
            ppp1=app.accel_vrs_peak;
            ppp2=[app.ffn 3*a_vrs];
            ppp3=[app.ffn   a_vrs];
            data1=[ app.accel_vrs_peak(:,2),3*a_vrs,a_vrs];
            
            leg1='peak';
            leg2='3-sigma';
            leg3='1-sigma';
            
            x_label='Natural Frequency (Hz)';
            if(app.iu<=2)
                y_label='Accel (G)';
            else
                y_label='Accel ((m/sec^2))';    
            end
            
            t_string = sprintf(' Acceleration Vibration Response Spectrum Q=%g ',app.Q);
            
            md=6;
            
            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,app.fmin,app.fmax,md);
                                   
            %%%%%%%%%%%%%%%%%%%%
            
            nss=length( app.accel_vrs_peak(:,1) );
            
            ffmin=app.accel_vrs_peak(1,1);
            ffmax=app.accel_vrs_peak(nss,1);
            
            [~,n1] = min(abs(app.ffn-ffmin));
            [~,n2] = min(abs(app.ffn-ffmax));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            md=6;
            
            ppp1=app.pv_vrs_peak;
            ppp2=[app.ffn 3*pv_vrs];
            ppp3=[app.ffn   pv_vrs];
            data2=[ app.pv_vrs_peak(:,2),3*pv_vrs,pv_vrs];
            
            leg1='peak';
            leg2='3-sigma';
            leg3='1-sigma';
            
            [C,I]=max(app.pv_vrs_peak(:,2));
            if(app.iu<=1)
                ylab='PV (in/sec)';
                out1=sprintf(' Maximum Peak PV = %7.3g in/sec at %8.4g Hz',C,app.pv_vrs_peak(I,1));
            else
                ylab='PV (m/sec)';    
                out1=sprintf(' Maximum Peak PV = %7.3g m/sec at %8.4g Hz',C,app.ffn(I));
            end           
            disp(out1);      
            
            y_label=ylab;
            x_label='Natural Frequency (Hz)';
            t_string = sprintf(' Pseudo Velocity Vibration Response Spectrum Q=%g ',app.Q);
            
            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,app.fmin,app.fmax,md);
                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            md=6;
            
            ppp1=app.rd_vrs_peak;
            ppp2=[app.ffn 3*rd_vrs];
            ppp3=[app.ffn   rd_vrs];
            data3=[ app.rd_vrs_peak(:,2),3*rd_vrs,rd_vrs];
            
            leg1='peak';
            leg2='3-sigma';
            leg3='1-sigma';
            
            if(app.iu<=1)
                ylab='Rel Disp (in)';
            else
                ylab='Rel Disp (mm)';    
            end
            y_label=ylab;
            x_label='Natural Frequency (Hz)';
            
            t_string = sprintf(' Relative Displacement Vibration Response Spectrum Q=%g ',app.Q);
            
            [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,app.fmin,app.fmax,md);
              
            if(app.iu==1)
               [fig_num]=subplots_vrs_set(fig_num,app.ffn,data1,data2,data3,app.Q,app.fmin,app.fmax); 
            end
                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(idc==1)
            %
                disp(' ');
                disp(' The fatigue damage spectrum is (peak-valley)/2 ');
                if(app.ftype==1)
                   nmetric=1;
                end
                if(app.ftype==2)
                   nmetric=2;  
                end
                if(app.ftype==3)
                   nmetric=4;
                end
            %
                x_label='Natural Frequency (Hz)';
                ppp=[app.ffn damage];
                
                [fig_num]=plot_fds_function(fig_num,x_label,ppp,app.fmin,app.fmax,nmetric,app.Q,bex);
            
                app.SaveFDSButton.Visible='on';
                app.FDSOutputArrayNameEditField.Visible='on';
                app.FDSOutputArrayNameEditFieldLabel.Visible='on';                    
            %     
            end        
            
            app.SaveVRSButton.Visible='on';
            app.VRSTypeListBox.Visible='on';
            app.VRSTypeListBoxLabel.Visible='on';            
            app.VRSTypeListBox_2.Visible='on';
            app.VRSOutputArrayNameEditField.Visible='on';
            app.VRSOutputArrayNameEditFieldLabel.Visible='on';            
            
        end

        % Callback function
        function NaturalFrequencyHzEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.SaveButton.Enable='off'; 
        end

        % Value changed function: QEditField
        function QEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.SaveButton.Enable='off'; 
        end

        % Value changed function: AmplitudeUnitsListBox
        function AmplitudeUnitsListBoxValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.SaveButton.Enable='off'; 
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';
            app.SaveButton.Enable='off';     
        end

        % Value changed function: OutputFrequencySpacingListBox
        function OutputFrequencySpacingListBoxValueChanged(app, event)
            app.ResultsTextArea.Enable='off'; 
            app.SaveButton.Enable='off';           
        end

        % Callback function
        function InterpolateInputListBoxValueChanged(app, event)
            app.ResultsTextArea.Enable='off';  
            app.SaveButton.Enable='off'; 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
          
            name=strtrim(app.OutputArrayNameEditField.Value);
            L=strtrim(app.ExporttoMatlabWorkspaceListBox.Value);
            
            if(strcmp(L,'Acceleration PSD'))
                data=app.accel_response_psd;
            end
            if(strcmp(L,'Relative Displacement PSD'))
                data=app.rd_response_psd; 
            end
            if(strcmp(L,'Acceleration Power Trans'))
                data=app.accel_power_trans;
            end         
            
            try
                assignin('base',name, data);                
            catch
                warndlg('save failed');
            end              
           
            app.RelativeFatigueDamageButton.Enable='on';
            app.SteinbergFatigueDamageButton.Enable='on';
            
        end

        % Value changed function: CalculateFunctionsListBox
        function CalculateFunctionsListBoxValueChanged(app, event)
            ChangeVRS(app);
        end

        % Button pushed function: SaveVRSButton
        function SaveVRSButtonPushed(app, event)
            
            ss=app.VRSTypeListBox_2.Value;
            
            if(app.vtype==1)
                if(contains(ss,'1'))
                    data=app.accel_vrs_1_sigma;               
                end
                if(contains(ss,'3'))
                    data=app.accel_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.accel_vrs_peak;              
                end                   
            end
            if(app.vtype==2)
                if(contains(ss,'1'))             
                    data=app.pv_vrs_1_sigma;                      
                end
                if(contains(ss,'3'))
                    data=app.pv_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.pv_vrs_peak;                  
                end                    
            end
            if(app.vtype==3)
                if(contains(ss,'1'))
                    data=app.rd_vrs_1_sigma;                     
                end
                if(contains(ss,'3'))
                    data=app.rd_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.rd_vrs_peak;                    
                end                
            end    
                    
            name=app.VRSOutputArrayNameEditField.Value;
            
            try
                assignin('base',name, data);                
            catch
                warndlg('save failed');
            end 
            
        end

        % Button pushed function: SaveFDSButton
        function SaveFDSButtonPushed(app, event)

            data=app.fds;
            name=app.FDSOutputArrayNameEditField.Value;
            
            try
                assignin('base',name, data);                
            catch
                warndlg('save failed');
            end 
        end

        % Value changed function: VRSTypeListBox
        function VRSTypeListBoxValueChanged(app, event)
            ChangeVRS_aux(app);
        end

        % Value changed function: FatigueTypeListBox
        function FatigueTypeListBoxValueChanged(app, event)
            ChangeVRS_aux(app);
            app.FDSOutputArrayNameEditField.Visible='off';
            app.FDSOutputArrayNameEditFieldLabel.Visible='off'; 
            app.SaveFDSButton.Visible='off';           
        end

        % Button pushed function: RelativeFatigueDamageButton
        function RelativeFatigueDamageButtonPushed(app, event)
            handles.s=response_PSD_relative_damage;
            set(handles.s,'Visible','on')
        end

        % Button pushed function: SteinbergFatigueDamageButton
        function SteinbergFatigueDamageButtonPushed(app, event)
            handles.s=Steinberg_PSD_fatigue;
            set(handles.s,'Visible','on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataPSDSDOFBaseUIFigure = uifigure('Visible', 'off');
            app.UIVibrationdataPSDSDOFBaseUIFigure.Name = 'UI Vibrationdata PSD SDOF Base';
            app.ReturnButton = uibutton(app.UIVibrationdataPSDSDOFBaseUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.vibrationdata_psd_sdof_base_aver10byTomIrvineLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.vibrationdata_psd_sdof_base_aver10byTomIrvineLabel.Text = 'vibrationdata_psd_sdof_base_a      ver 1.0   by Tom Irvine           This script calculates the reponse of an SDOF system to a PSD base input.   The input array must have two columns: freq (Hz) & accel (unit^2/Hz)';
            app.DurationsecEditFieldLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UIVibrationdataPSDSDOFBaseUIFigure, 'numeric');
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.DurationsecEditField.Value = 60;
            app.Image = uiimage(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.Image.ImageSource = 'sdof_base.jpg';
            app.QEditFieldLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIVibrationdataPSDSDOFBaseUIFigure, 'numeric');
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @QEditFieldValueChanged, true);
            app.QEditField.Value = 10;
            app.TabGroup = uitabgroup(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.GivenfnTab = uitab(app.TabGroup);
            app.GivenfnTab.Title = 'Given fn';
            app.CaculateButton = uibutton(app.GivenfnTab, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.ResultsTextAreaLabel = uilabel(app.GivenfnTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.GivenfnTab);
            app.ExporttoMatlabWorkspaceListBoxLabel = uilabel(app.GivenfnTab);
            app.ExporttoMatlabWorkspaceListBoxLabel.Text = 'Export to Matlab Workspace';
            app.ExporttoMatlabWorkspaceListBox = uilistbox(app.GivenfnTab);
            app.ExporttoMatlabWorkspaceListBox.Items = {'Acceleration PSD', 'Relative Displacement PSD', 'Acceleration Power Trans'};
            app.ExporttoMatlabWorkspaceListBox.Value = 'Acceleration Power Trans';
            app.OutputArrayNameEditFieldLabel = uilabel(app.GivenfnTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.GivenfnTab, 'text');
            app.SaveButton = uibutton(app.GivenfnTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NaturalFrequencyHzEditFieldLabel = uilabel(app.GivenfnTab);
            app.NaturalFrequencyHzEditFieldLabel.Text = 'Natural Frequency (Hz)';
            app.NaturalFrequencyHzEditField = uieditfield(app.GivenfnTab, 'numeric');
            app.RelativeFatigueDamageButton = uibutton(app.GivenfnTab, 'push');
            app.RelativeFatigueDamageButton.ButtonPushedFcn = createCallbackFcn(app, @RelativeFatigueDamageButtonPushed, true);
            app.RelativeFatigueDamageButton.Text = 'Relative Fatigue Damage';
            app.SteinbergFatigueDamageButton = uibutton(app.GivenfnTab, 'push');
            app.SteinbergFatigueDamageButton.ButtonPushedFcn = createCallbackFcn(app, @SteinbergFatigueDamageButtonPushed, true);
            app.SteinbergFatigueDamageButton.Text = 'Steinberg Fatigue Damage';
            app.VRSTab = uitab(app.TabGroup);
            app.VRSTab.Title = 'VRS';
            app.CaculateVRSButton = uibutton(app.VRSTab, 'push');
            app.CaculateVRSButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateVRSButtonPushed, true);
            app.CaculateVRSButton.Text = 'Caculate VRS';
            app.CalculateFunctionsListBoxLabel = uilabel(app.VRSTab);
            app.CalculateFunctionsListBoxLabel.Text = 'Calculate Functions';
            app.CalculateFunctionsListBox = uilistbox(app.VRSTab);
            app.CalculateFunctionsListBox.Items = {'VRS', 'VRS & FDS'};
            app.CalculateFunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @CalculateFunctionsListBoxValueChanged, true);
            app.CalculateFunctionsListBox.Value = 'VRS';
            app.FatigueExponentEditFieldLabel = uilabel(app.VRSTab);
            app.FatigueExponentEditFieldLabel.Text = 'Fatigue Exponent';
            app.FatigueExponentEditField = uieditfield(app.VRSTab, 'numeric');
            app.FatigueExponentEditField.Value = 4;
            app.FatigueTypeListBoxLabel = uilabel(app.VRSTab);
            app.FatigueTypeListBoxLabel.Text = 'Fatigue Type';
            app.FatigueTypeListBox = uilistbox(app.VRSTab);
            app.FatigueTypeListBox.Items = {'Acceleration', 'Pseudo Velocity', 'Relative Disp'};
            app.FatigueTypeListBox.ValueChangedFcn = createCallbackFcn(app, @FatigueTypeListBoxValueChanged, true);
            app.FatigueTypeListBox.Value = 'Acceleration';
            app.FDSOutputArrayNameEditFieldLabel = uilabel(app.VRSTab);
            app.FDSOutputArrayNameEditFieldLabel.Text = 'FDS Output Array Name';
            app.FDSOutputArrayNameEditField = uieditfield(app.VRSTab, 'text');
            app.SaveFDSButton = uibutton(app.VRSTab, 'push');
            app.SaveFDSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFDSButtonPushed, true);
            app.SaveFDSButton.Text = 'Save FDS';
            app.SaveVRSButton = uibutton(app.VRSTab, 'push');
            app.SaveVRSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVRSButtonPushed, true);
            app.SaveVRSButton.Text = 'Save VRS';
            app.VRSTypeListBoxLabel = uilabel(app.VRSTab);
            app.VRSTypeListBoxLabel.Text = 'VRS Type';
            app.VRSTypeListBox = uilistbox(app.VRSTab);
            app.VRSTypeListBox.Items = {'Acceleration', 'Pseudo Velocity', 'Relative Disp'};
            app.VRSTypeListBox.ValueChangedFcn = createCallbackFcn(app, @VRSTypeListBoxValueChanged, true);
            app.VRSTypeListBox.Value = 'Acceleration';
            app.VRSOutputArrayNameEditFieldLabel = uilabel(app.VRSTab);
            app.VRSOutputArrayNameEditFieldLabel.Text = 'VRS Output Array Name';
            app.VRSOutputArrayNameEditField = uieditfield(app.VRSTab, 'text');
            app.VRSTypeListBox_2 = uilistbox(app.VRSTab);
            app.VRSTypeListBox_2.Items = {'1-sigma', '3-sigma', 'peak'};
            app.VRSTypeListBox_2.Value = '1-sigma';
            app.PlotFrequenciesHzLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIVibrationdataPSDSDOFBaseUIFigure, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIVibrationdataPSDSDOFBaseUIFigure, 'numeric');
            app.MaxEditField.Value = 2000;
            app.OutputFrequencySpacingListBoxLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.OutputFrequencySpacingListBoxLabel.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox = uilistbox(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.OutputFrequencySpacingListBox.Items = {'Constant 0.5 Hz', '1/3 octave', '1/6 octave', '1/12 octave', '1/24 octave'};
            app.OutputFrequencySpacingListBox.ValueChangedFcn = createCallbackFcn(app, @OutputFrequencySpacingListBoxValueChanged, true);
            app.OutputFrequencySpacingListBox.Value = '1/12 octave';
            app.AmplitudeUnitsListBoxLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.AmplitudeUnitsListBoxLabel.Text = 'Amplitude Units';
            app.AmplitudeUnitsListBox = uilistbox(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.AmplitudeUnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm', 'm/sec^2, m/sec, mm'};
            app.AmplitudeUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeUnitsListBoxValueChanged, true);
            app.AmplitudeUnitsListBox.Value = 'G, in/sec, in';
            app.DurationonlyaffectsmaximumexpectedpeakFDSonlyLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.DurationonlyaffectsmaximumexpectedpeakFDSonlyLabel.Text = '(Duration only affects maximum expected peak & FDS only)';
            app.InputArrayNameEditFieldLabel = uilabel(app.UIVibrationdataPSDSDOFBaseUIFigure);
            app.InputArrayNameEditFieldLabel.Text = 'Input Array Name';
            app.InputArrayNameEditField = uieditfield(app.UIVibrationdataPSDSDOFBaseUIFigure, 'text');
            % Show the figure after all components are created
            app.UIVibrationdataPSDSDOFBaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_psd_sdof_base_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIVibrationdataPSDSDOFBaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataPSDSDOFBaseUIFigure)
        end
    end
end