classdef vibrationdata_psd_synth_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSDSynthesisUIFigure            matlab.ui.Figure
        ReturnButton                    matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsfrequencyHzamplitudeunit2HzLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DataEntryTab                    matlab.ui.container.Tab
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        SelectPSDInputArrayListBox      matlab.ui.control.ListBox
        SelectPSDInputArrayListBoxLabel  matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        InputTypeListBox                matlab.ui.control.ListBox
        InputTypeListBoxLabel           matlab.ui.control.Label
        AmplitudeUnitEditField          matlab.ui.control.EditField
        AmplitudeUnitEditFieldLabel     matlab.ui.control.Label
        ReadDataButton                  matlab.ui.control.Button
        CalculateTimeHistoryTab         matlab.ui.container.Tab
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        ProcessingOptionsLabel          matlab.ui.control.Label
        SelectdofListBox                matlab.ui.control.ListBox
        SelectdofListBoxLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CalculateTimeHistoryButton      matlab.ui.control.Button
        AddSineTonesTab                 matlab.ui.container.Tab
        HIghestsinefrequencyshouldbehighestPSDfrequencyLabel  matlab.ui.control.Label
        NumberofTonesListBox            matlab.ui.control.ListBox
        NumberofTonesListBoxLabel       matlab.ui.control.Label
        CalculateButton_multi           matlab.ui.control.Button
        UITable_sine_tones              matlab.ui.control.Table
        AddSineSweepTab                 matlab.ui.container.Tab
        HIghestsinefrequencyshouldbehighestPSDfrequencyLabel_2  matlab.ui.control.Label
        CalculateButton_sweep           matlab.ui.control.Button
        NumberofCoordinatesListBox      matlab.ui.control.ListBox
        NumberofCoordinatesListBoxLabel  matlab.ui.control.Label
        SweepDirectionListBox           matlab.ui.control.ListBox
        SweepDirectionListBoxLabel      matlab.ui.control.Label
        SweepTypeListBox                matlab.ui.control.ListBox
        SweepTypeListBoxLabel           matlab.ui.control.Label
        UITable_sweep                   matlab.ui.control.Table
        ExportTimeHistoryStatisticsTab  matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        SDOFResponsetoBaseInputButton   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        FunctionListBox                 matlab.ui.control.ListBox
        FunctionListBoxLabel            matlab.ui.control.Label
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        THM     % time history array
        fff     % time column
        amp     % amplitude column
        s1      % amplitude dimension
        s2      % amplitude unit
        s3      %
        dt      % time step
        df      % frequency step
        num     % number of points 
        nhalf   % half the number of points
        AA      % signal processing option array
        fig_num % figure number 
        Fourier_magnitude       % 
        power_spectral_density  % 
        fmin %
        fmax %
        rms  %
        x_label %
        y_label %
        N %
        iflag %
        sr  %
        NW  %
        mmm  %
        psd_spec %
        method   %
        duration %
        L  %
        LL %
        accel    %
        velox    %
        dispx    %
        psd_syn  %
        time_synth %  
        iu         %
        TT         %
        psd_TH     %
        psd_th     %
        psd_velox  %
        psd_dispx  %
        sss        %
        swnum      %
    end
    
    methods (Access = private)

       function ChangeNumberCoordinates(app)
            ns=app.NumberofCoordinatesListBox.Value;
            app.swnum=str2num(ns); 
            
            q=zeros(app.swnum,2);
            
            app.UITable_sweep.Data=q;
            
        end

        function plot_time_histories(app)

            nbars=31;

            ppp=[app.TT app.psd_TH];

            app.time_synth=ppp;

            xL='Time (sec)';
            yL=sprintf('%s (%s)',app.s2,app.s3);

            if(app.LL==1)
                t_string=sprintf('%s Time History Synthesis  %7.3g %sRMS',app.s1,std(app.psd_TH),app.s3);
            else
                t_string=sprintf('%s Time History Synthesis  %7.3g %s RMS',app.s1,std(app.psd_TH),app.s3);               
            end

            [app.fig_num]=plot_time_history_histogram(app.fig_num,ppp,t_string,xL,yL,nbars);  

            clear app.sss; 

            if(app.LL==1)
                
                unit='G';
                aat=sprintf('Acceleration Time History Synthesis  %7.3g %s rms',std(app.psd_th),unit);
                ay=sprintf('Acceleration (%s)',unit);
                
                if(app.iu==1)
                    unit='in/sec';
                else
                    unit='cm/sec';    
                end
                vvt=sprintf('Velocity Time History Synthesis  %7.3g %s rms',std(app.psd_velox),unit);
                vy=sprintf('Velocity (%s)',unit);
                
                if(app.iu==1)
                    unit='in';
                else
                    unit='mm';    
                end
                ddt=sprintf('Displacement Time History Synthesis  %7.3g %s rms',std(app.psd_dispx),unit);
                dy=sprintf('Displacement (%s)',unit);
                 
                tt=app.TT;
                a=app.psd_TH;
                v=app.psd_velox;
                d=app.psd_dispx;

                a=fix_size(a);
                v=fix_size(v);
                d=fix_size(d);

                [app.fig_num,~]=plot_avd_time_histories_subplots_altp_titles(app.fig_num,tt,a,v,d,ay,vy,dy,aat,vvt,ddt);

                app.accel=[tt a];
                app.velox=[tt v];
                app.dispx=[tt d];

                app.sss{1}='Acceleration Time History';
                app.sss{2}='Velocity Time History';
                app.sss{3}='Displacement Time History';
                app.sss{4}='Acceleration PSD';                
            else
                app.sss{1}='Time History';
                app.sss{2}='PSD';
            end

        end    

        function LB_change(app)

            M=str2num(app.NumberofTonesListBox.Value);
           
            for i = 1:M
               for j=1:2
                  data_s{i,j} = '';     
               end  
               data_s{i,3} = '0';   
            end
 
            app.UITable_sine_tones.Data=data_s;          
        end        

        function InputChange(app)
            
            app.L=app.InputTypeListBox.Value;
            
            app.SelectUnitsListBox.Visible='off';
            app.SelectUnitsListBoxLabel.Visible='off';
            app.AmplitudeUnitEditField.Visible='off';
            app.AmplitudeUnitEditFieldLabel.Visible='off';
            
            if(contains(app.L,'Acceleration'))
                app.SelectUnitsListBox.Visible='on';
                app.SelectUnitsListBoxLabel.Visible='on';            
            else
                app.AmplitudeUnitEditField.Visible='on';
                app.AmplitudeUnitEditFieldLabel.Visible='on';            
            end
        end    

        function array_method(app)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectPSDInputArrayListBox.Value=name;
            end    
        end
        function Choice(app)

            app.SDOFResponsetoBaseInputButton.Visible='off';  
            app.ResultsTextArea.Value='';
            app.SaveButton.Enable='off';           

            app.sr=12*max(app.THM(:,1));

            app.duration=str2num(app.DurationsecEditField.Value);
            app.num=round(app.duration*app.sr);
            app.num=2*floor(app.num/2);   % make even
            app.nhalf=floor(app.num/2);
            app.dt=1/app.sr;
            app.amp=app.THM(:,2);
            app.fff=app.THM(:,1);
            
            %%%
            
            app.N=floor(log(app.num)/log(2));
            
            if(app.N>12)
                app.N=12;
            end
            
            ss={'2','4','8','16','32','64','128','256','512','1024','2048','4096'};            
            sq={'1','2','3', '4', '5', '6',  '7',  '8',  '9',  '10',  '11', '12'};            
             
            A=zeros(app.N,5);
            
            for i=1:app.N
                m=2^(i-1); 
                A(i,1)=m;      
                A(i,2)=floor(app.num/m);
                A(i,3)=A(i,2)*app.dt;
                A(i,4)=1/A(i,3);
                A(i,5)=2*A(i,1);
            end  
            
            myData=array2table(A);
            app.UITable.Data=myData;  
            
            for i=10:-1:app.N
                ss(end)=[];
                sq(end)=[];                
            end
  
            app.SelectdofListBox.Items=ss;
            % app.UITable.RowName=sq;
            
            app.AA=A;
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if( strcmp(vars(i).name,'mode_1') || ...
                    strcmp(vars(i).name,'mode_2') || ...
                    strcmp(vars(i).name,'mode_3') || ...
                    strcmp(vars(i).name,'mode_4') || ...
                    strcmp(vars(i).name,'mode_5') || ...   
                    contains(vars(i).name,'Dirlik_N_') || ...
                    contains(vars(i).name,'_fds_Q') || ...
                    contains(vars(i).name,'myHandle') || ...
                    contains(vars(i).name,'vname_list') || ...
                    contains(vars(i).name,'amp_cycles') || ...
                    contains(vars(i).name,'range_cycles') || ...
                    contains(vars(i).name,'amp_mean_cycles') || ...
                    contains(vars(i).name,'range_cycles_max_min'))
                    cell_array{i} ='999';
                else    
                    ggg=evalin('base',vars(i).name);
                    sz=size(ggg);

                    try
                        mint2=min(ggg(:,2));
    
                        if(sz(2)==2 && mint2>=0)
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end
                    catch
                        cell_array{i} ='999';
                    end    
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.SelectPSDInputArrayListBox.Items=unique(sort(cell_array)); 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            minimum_workmanship();
            update(app);
            ClosePlots(app);
            InputChange(app);
            LB_change(app);
            ChangeNumberCoordinates(app);
            app.SDOFResponsetoBaseInputButton.Visible='off';  
            app.ResultsTextArea.Value='';
            app.SaveButton.Enable='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            ClosePlots(app);
            app.SDOFResponsetoBaseInputButton.Visible='off';
            app.ResultsTextArea.Value='';
            app.SaveButton.Enable='off';
            
            app.fig_num=1;

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectPSDInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            else
            
                try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

                catch
                   warndlg('Read unsuccessful');
                   return;
                end

                try
                    [~, name, ~] = fileparts(filename);
                catch
                    warndlg('ref 1')
                end

                try
                    assignin('base',name,app.THM);
                catch
                    warndlg('ref 2')
                end

               update(app);
               app.SelectPSDInputArrayListBox.Value=name;
            end                
            
            if(isempty(app.THM))
                warndlg('Input Array not found')
                return;
            end    
            
            app.L=app.InputTypeListBox.Value;
            
            app.s1='';
            app.s2='';
            app.LL=0;
            
            if(contains(app.L,'Acceleration'))
                app.LL=1;
                app.s1='Acceleration';
                app.s2='Accel';
            end
            if(contains(app.L,'Velocity'))
                app.LL=2;
                app.s1='Velocity';
                app.s2='Vel';
            end
            if(contains(app.L,'Displacement'))
                app.LL=3;
                app.s1='Displacement';
                app.s2='Disp';
            end
            if(contains(app.L,'Force'))
                app.LL=4;
                app.s1='Force';
                app.s2=app.s1;
            end
            if(contains(app.L,'Pressure'))
                app.LL=5;
                app.s1='Pressure';
                app.s2=app.s1;
            end
              
            app.s3=app.AmplitudeUnitEditField.Value;
            
            if(app.iflag==999)
                return;
            end

            Choice(app);
            
            msgbox('Go to Calculate Time History tab');
            
        end

        % Button pushed function: CalculateTimeHistoryButton
        function CalculateTimeHistoryButtonPushed(app, event)
          
            app.fig_num=1;

            if(app.iflag==999)
                return;
            end

            nss=app.RandomNumberSeedListBox.Value;

            nseed=0;
            if(strcmp(nss,'1'))
                nseed=1;
            end    
            if(strcmp(nss,'2'))
                nseed=2;
            end 
            if(strcmp(nss,'3'))
                nseed=3;
            end 
            if(strcmp(nss,'4'))
                nseed=4;
            end 
            if(strcmp(nss,'5'))
                nseed=5;
            end 

            clear progressbar;
            progressbar;

            progressbar(2/10);
            
            np=app.num;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            b=app.SelectdofListBox.Value;
            for i=1:app.N
                a=2^i;
                ss=sprintf('%d',a);
                
                if(strcmp(b,ss))
                    % dof=a;
                    num_seg=app.AA(i,1);
                    samples_seg=app.AA(i,2);
                    app.df=app.AA(i,4);
                    break;
                end
            end  
            
            app.NW=num_seg;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            progressbar(3/10);  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.THM(1,1)<1.0e-09)  % check for zero frequency
                app.THM(1,1)=10^(floor(-0.1+log10(app.THM(2,1))));
            end
            
            if(app.THM(1,2)<1.0e-30)  % check for zero amplitude
                noct=log(app.THM(2,1)/app.THM(1,1))/log(2);
                app.THM(1,2)=(noct/4)*app.THM(2,2);         % 6 db/octave 
            end
            
            nsz=max(size(app.THM));
            freq=zeros(nsz+1,1);
            app.amp=zeros(nsz+1,1);
            
            k=1;
            for i=1:nsz
                if(app.THM(i,1)>0)
                    app.amp(k)=app.THM(i,2);
                    freq(k)=app.THM(i,1);
                    k=k+1;
                end
            end
            
            original_spec=app.THM;
            
            progressbar(4/10);
            
            freq(nsz+1)=freq(nsz)*2^(1/48);
            app.amp(nsz+1)=app.amp(nsz);
            
            [~,slope,app.rms] = psd_syn_data_entry(freq,app.amp,nsz,nsz);
            
            progressbar(5/10);
            
            freq=fix_size(freq);
            app.amp=fix_size(app.amp);
             
            spec_rms=app.rms; 

            tmax=app.duration;
            
            progressbar(6/10);
            
            %   
            %  Generate White Noise 
            %
            m_choice=1; % mean removal
            h_choice=1; % rectangular window
            
            [np,white_noise,~]=PSD_syn_white_noise_seed(app.dt,np,nseed);
            
            % Technique for improving time history accuracy
            
            [~,~,~,complex_FFT]=full_FFT_core(m_choice,h_choice,white_noise,np,app.dt);
            alpha1=atan2(complex_FFT(:,2),complex_FFT(:,3));
            XX=( cos(alpha1) + (1i)*sin(alpha1)); 
            white_noise=ifft(XX,np,'symmetric');
            
            progressbar(7/10);
            %
            %  Interpolate PSD spec
            %
            app.mmm=round(np/2);
            %

            ffv=freq(1)/(2^(1/3));  % 1/3 octave 

            n=log(app.amp(2)/app.amp(1))/log(freq(2)/freq(1));

            aaa=app.amp(1)*(ffv/freq(1))^n;

            freq=[ffv; freq];
            app.amp=[aaa; app.amp];

            for i=1:length(freq)
                fprintf(' %7.3g \n',app.amp(i));
            end    

            app.df=1/(np*app.dt);
            [~,app.psd_spec]=interpolate_PSD_spec(np,freq,app.amp,slope,app.df);
            %

            app.fmax=max(freq);
            
            progressbar(8/10);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            nsegments = 1;
            %
            sq_spec=sqrt(app.psd_spec);
            %
            %  Plot Input PSD
            %
            if(app.LL==1)
                app.s3='G';
                out1=sprintf(' Input Power Spectral Density %7.3g %sRMS',app.rms,app.s3);
            else
                out1=sprintf(' Input Power Spectral Density %7.3g %s RMS',app.rms,app.s3);               
            end              
            [app.fig_num]=wnb_PSD_plot(app.fig_num,original_spec,out1,app.s2,app.s3);
            %

            progressbar(1);
            clear progressbar;
            
            [~,app.psd_th,~]=PSD_syn_FFT_core(nsegments,app.mmm,np,app.fmax,app.df,sq_spec,white_noise);
            %
            
            disp('r1: size app.psd_th');
            size(app.psd_th)
            
            [app.TT,app.psd_th,app.dt]=PSD_syn_scale_time_history(app.psd_th,app.rms,np,tmax);
            
            disp('r2: size app.psd_th');
            size(app.psd_th)
            
            [app.amp,mr_choice,h_choice,~]=...
                                   wnb_PSD_syn_verify(app.TT,app.psd_th,spec_rms,app.dt,app.df,app.mmm,app.NW);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            clear freq_spec;
            clear amp_spec;
            %
            freq_spec=original_spec(:,1);
            amp_spec=original_spec(:,2);          

            nnt=4;

            if(app.LL==1)

                Items = char(app.SelectUnitsListBox.Items);
                im=app.SelectUnitsListBox.Value;
                [app.iu]=find_index(Items,im); 
                
                [app.amp,app.velox,app.dispx,freq,full,tim,app.df]=...
                      wnaccel_psd_syn_correction(nnt,app.amp,app.dt,spec_rms,app.NW,freq_spec,...
                                          amp_spec,mr_choice,h_choice,app.TT,app.iu,spec_rms);
            else

                [app.amp,~,~,~]=...
                       wnb_PSD_syn_verify(app.TT,app.psd_th,spec_rms,app.dt,app.df,app.mmm,app.NW);               
       
                [app.amp,freq,full,tim,app.df]=...
                        wnd_generic_psd_syn_correction(nnt,app.amp,app.dt,spec_rms,app.NW,freq_spec,...
                          amp_spec,mr_choice,h_choice,app.TT);                         
            end    

            scale=std(app.amp)/spec_rms;
            app.amp=app.amp*scale;

            if(app.LL==1)
                app.velox=app.velox*scale;
                app.dispx=app.dispx*scale;
            end
            
            full=full*scale^2;
           
            freq=fix_size(freq);
            full=fix_size(full);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            % rms=sqrt(ms*df);
            %
            
            [~,~,app.rms,sk,kt]=kurtosis_stats(app.amp);
            
            crest=max(abs(app.amp))/app.rms;
            
            out4 = sprintf('  Overall RMS = %10.3g \n',app.rms);
            out5 = sprintf('  Three Sigma = %10.3g \n\n',3*app.rms);
            
            mx=max(app.amp);
            mi=min(app.amp);
                
            out6 = sprintf('      Maximum = %8.4g \n',mx);
            out7 = sprintf('      Minimum = %8.4g \n\n',mi);
         
            out8 = sprintf('     Skewness = %8.2g \n',sk);           
            out9 = sprintf('     Kurtosis = %8.3g \n',kt);
            out10 = sprintf(' Crest Factor = %8.3g ',crest);
            
            app.ResultsTextArea.Value=sprintf('%s %s %s %s %s %s %s',out4,out5,out6,out7,out8,out9,out10);
            
            disp(' ');
            %
            clear app.TT;
            clear app.psd_TH;
            %
            app.TT=tim;
            app.psd_TH=app.amp;

            if(app.LL==1)
                app.psd_velox=app.velox;
                app.psd_dispx=app.dispx;
            end
            %
            app.TT=fix_size(app.TT);
            %
            clear length;
            na=length(app.psd_TH);
            %
            temp=app.TT(1:na);
            clear app.TT;
            app.TT=temp;
            
            %
            if(app.LL==1)
                app.psd_velox=fix_size(app.psd_velox);
                app.psd_dispx=fix_size(app.psd_dispx);
            end
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %

            plot_time_histories(app);
            
            app.FunctionListBox.Items=app.sss;
            
            freq=fix_size(freq);
            full=fix_size(full);
            
            app.psd_syn=[freq full];
            app.psd_syn(1,:)=[];
            
            h5=figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(freq,full,'b',freq_spec,amp_spec,'r',...
                       freq_spec,sqrt(2)*amp_spec,'k',freq_spec,amp_spec/sqrt(2),'k');
            legend ('Synthesis','Specification','+/- 1.5 dB tol ');
            %   
            app.fmin=freq_spec(1);
            app.fmax=max(freq_spec);
            ymax=max(full);
            %  
            xlabel(' Frequency (Hz)');
            
            if(app.LL==1)
                at=sprintf('Power Spectral Density %7.3g %sRMS',app.rms,app.s3);
            else
                at=sprintf('Power Spectral Density %7.3g %s RMS',app.rms,app.s3);               
            end      
                       
            out1=sprintf('%s (%s^2/Hz)  ',app.s2,app.s3);
            ylabel(out1); 
 
            title(at);
            set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','log'); 
            grid;
            %
            tb=sqrt(sqrt(2));
            ymax=10^(ceil(+0.1+log10(ymax)));
            ymin = min(amp_spec/tb);
            ymin=10^(floor(-0.1+log10(ymin)));
            %
            app.fmax=10^(ceil(log10(app.fmax)));
            app.fmin=10^(floor(log10(app.fmin)));
            %
            xlim([app.fmin,app.fmax]);
            ylim([ymin,ymax]);

            app.SDOFResponsetoBaseInputButton.Visible='off'; 
            app.SaveButton.Enable='on';    

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            name=app.OutputArrayNameEditField.Value;

            Items = char(app.FunctionListBox.Items);
            im=app.FunctionListBox.Value;
            [n]=find_index(Items,im);
            
            if(app.LL==1)
                if(n==1)
                    data=app.accel;
                end
                if(n==2)
                    data=app.velox;
                end
                if(n==3)
                    data=app.dispx;
                end
                if(n==4)
                    data=app.psd_syn;
                end          
            else
                if(n==1)
                    data=app.time_synth;
                else    
                    data=app.psd_syn;
                end
            end
            
            assignin('base',name, data);

            if(app.LL==1)
                app.SDOFResponsetoBaseInputButton.Visible='on';
            else
                app.SDOFResponsetoBaseInputButton.Visible='off';            
            end    

            msgbox('Save Complete');
            
        end

        % Callback function
        function PlotButtonPushed(app, event)

        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
            
        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button down function: CalculateTimeHistoryTab
        function CalculateTimeHistoryTabButtonDown(app, event)
            if(app.N>=1)
            else    
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end  
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            array_method(app);
        end

        % Callback function
        function ApplyTimeLimitsButtonPushed(app, event)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Segment'))
                tstart=app.StartTimesecEditField.Value;
                tend=app.EndTimesecEditField.Value;  
      
                [~,istart]=min(abs(tstart-app.THM(:,1)));      
                [~,iend]=min(abs(tend-app.THM(:,1)));      
       
                app.THM=app.THM(istart:iend,:);    

                Choice(app);

                msgbox('Go to Calculate PSD tab');
            end
        end

        % Button pushed function: SDOFResponsetoBaseInputButton
        function SDOFResponsetoBaseInputButtonPushed(app, event)
   
            sdof_response_time_domain;
        end

        % Value changed function: InputTypeListBox
        function InputTypeListBoxValueChanged(app, event)
            InputChange(app);
        end

        % Button pushed function: CalculateButton_multi
        function CalculateButton_multiPushed(app, event)
            sz=size(app.accel);
            nt=sz(1);

            if(nt==0)
                warndlg('Calculate Time History first');
                return;
            end  

            M=str2num(app.NumberofTonesListBox.Value);
            
            A=char(app.UITable_sine_tones.Data);
            
            B=str2num(A);
            
            peak=B(1:M);
            freq=B((M+1):(2*M)); 
            phase=B((2*M+1):(3*M)); 
            
            phase=phase*pi/180;

            omega=2.*pi*freq;
        %    
            a = zeros(nt,1);  
        %    
            for i=1:M
                a=a+peak(i)*sin(omega(i)*app.TT-phase(i));
            end  

            a=a+app.accel(:,2);
            
            kvn=1;
            fstart=app.THM(1,1);

            if(app.LL==1)
                [acc,app.velox,app.dispx]=velox_correction(a,app.dt,kvn,fstart,app.iu);
            
                app.psd_th = acc;
                app.psd_TH = acc;
                app.psd_velox = app.velox;
                app.psd_dispx = app.dispx;
            end

            app.psd_th = a;
            app.psd_TH = a;  
            app.accel = a;

            plot_time_histories(app);

        end

        % Value changed function: NumberofTonesListBox
        function NumberofTonesListBoxValueChanged(app, event)
       
            LB_change(app);
        end

        % Button pushed function: CalculateButton_sweep
        function CalculateButton_sweepPushed(app, event)
            sz=size(app.accel);
            nt=sz(1);

            if(nt==0)
                warndlg('Calculate Time History first');
                return;
            end  

            n=app.SweepTypeListBox.Value;
            if(strcmp(n,'linear'))
                ntype=1;
            else
                ntype=2;
            end

            M=app.swnum;
            
            ndirs=app.SweepDirectionListBox.Value;
            
            if(strcmp(ndirs,'up'))
                ndir=1;
            end
            if(strcmp(ndirs,'down'))
                ndir=2;
            end            
            
            B=app.UITable_sweep.Data;
            
            freq=B(1:M);
            swamp=B((M+1):(2*M));
            
            freq=fix_size(freq);
            swamp=fix_size(swamp);
            
            fa=[freq swamp];
            
            fa=sortrows(fa,1);
            
            app.fff=fa(:,1);
            aaa=fa(:,2);
            
            f1=app.fff(1);
            f2=app.fff(end);
            oct=log2(f2/f1);

            dur=app.TT(end)-app.TT(1);

            tpi=2.*pi;
            
            if(ntype==1)  % linear
                rate=(f2-f1)/dur;
            else              % log
                rate=oct/dur;
            end

               a = zeros(1,nt);
             arg = zeros(1,nt);
            freq = zeros(1,nt);
            
            if(ntype==1)  % linear
            %
            % 0.5 factor is necessary to obtain correct number of cycles for linear case.
            %
            %	fspectral= (     rate*t ) + f1;
            %
                ffmax=0.5*(f2-f1)+f1;
                freq=linspace(f1,ffmax,nt);     
            %
            else  % log
            %  
            %   fspectral = f1*pow(2.,rate*t);
            %
                for i=1:nt
                    arg(i)=-1.+2^(rate*app.TT(i));
                end   
            end
            
            freq=fix_size(freq);
            
            if(ntype==1) % linear
               arg=tpi*freq.*app.TT;
            else             % log
               arg=tpi*f1*arg/(rate*log(2));  
            end
            
            if(ntype==1) % linear
                spectral=linspace(f1,f2,nt); 
            else 
                f1=log10(f1);
                f2=log10(f2);
                spectral=logspace(f1,f2,nt);         
            end
            
            limit=length(app.fff)-1;
            amplitude=zeros(1,nt);
            
            for i=1:nt
               for j=1:limit
                   if(app.fff(j)<=spectral(i) && app.fff(j+1)>=spectral(i))
                        x=spectral(i)-app.fff(j);
                        Ls=app.fff(j+1)-app.fff(j);
                        c2=x/Ls;
                        c1=1-x/Ls;
                        amplitude(i)=c1*aaa(j)+c2*aaa(j+1);
                        break;
                   end
               end
            end
            
            a=sin(arg);
            
            a=fix_size(a);
            amplitude=fix_size(amplitude);
            
            a(1)=0.;
            a=a.*amplitude;
            a=fix_size(a);
            
            if(ndir==2)
                a=flipud(a);
            end

            a=a+app.accel(:,2);
            
            kvn=1;
            fstart=app.THM(1,1);

            if(app.LL==1)
                [acc,app.velox,app.dispx]=velox_correction(a,app.dt,kvn,fstart,app.iu);
            
                app.psd_th = acc;
                app.psd_TH = acc;
                app.psd_velox = app.velox;
                app.psd_dispx = app.dispx;
            end

            app.psd_th = a;
            app.psd_TH = a;  
            app.accel = a;

            plot_time_histories(app);
 
        end

        % Value changed function: NumberofCoordinatesListBox
        function NumberofCoordinatesListBoxValueChanged(app, event)
            ChangeNumberCoordinates(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PSDSynthesisUIFigure = uifigure('Visible', 'off');
            app.PSDSynthesisUIFigure.Name = 'PSD Synthesis';
            app.Label = uilabel(app.PSDSynthesisUIFigure);
            app.Label.Text = 'vibrationdata_psd_synth_ah   ver 2.0   by Tom Irvine   This script synthesizes a time history to satisfy a power spectral density.  ';
            app.TabGroup = uitabgroup(app.PSDSynthesisUIFigure);
            app.DataEntryTab = uitab(app.TabGroup);
            app.DataEntryTab.Title = 'Data Entry';
            app.ReadDataButton = uibutton(app.DataEntryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.AmplitudeUnitEditFieldLabel = uilabel(app.DataEntryTab);
            app.AmplitudeUnitEditFieldLabel.Text = 'Amplitude Unit';
            app.AmplitudeUnitEditField = uieditfield(app.DataEntryTab, 'text');
            app.InputTypeListBoxLabel = uilabel(app.DataEntryTab);
            app.InputTypeListBoxLabel.Text = 'Input Type';
            app.InputTypeListBox = uilistbox(app.DataEntryTab);
            app.InputTypeListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'Force', 'Pressure', 'Other'};
            app.InputTypeListBox.ValueChangedFcn = createCallbackFcn(app, @InputTypeListBoxValueChanged, true);
            app.InputTypeListBox.Value = 'Acceleration';
            app.listbox_method = uilistbox(app.DataEntryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.DataEntryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.DataEntryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectPSDInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectPSDInputArrayListBoxLabel.Text = 'Select PSD Input Array';
            app.SelectPSDInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectPSDInputArrayListBox.Items = {'Item 1'};
            app.DurationsecEditFieldLabel = uilabel(app.DataEntryTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DataEntryTab, 'text');
            app.DurationsecEditField.Value = '60';
            app.SelectUnitsListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.DataEntryTab);
            app.SelectUnitsListBox.Items = {'G, in/sec, in', 'G, cm/sec, mm'};
            app.SelectUnitsListBox.Value = 'G, in/sec, in';
            app.CalculateTimeHistoryTab = uitab(app.TabGroup);
            app.CalculateTimeHistoryTab.Title = 'Calculate Time History';
            app.CalculateTimeHistoryTab.ButtonDownFcn = createCallbackFcn(app, @CalculateTimeHistoryTabButtonDown, true);
            app.CalculateTimeHistoryButton = uibutton(app.CalculateTimeHistoryTab, 'push');
            app.CalculateTimeHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTimeHistoryButtonPushed, true);
            app.CalculateTimeHistoryButton.Text = 'Calculate Time History';
            app.UITable = uitable(app.CalculateTimeHistoryTab);
            app.UITable.ColumnName = {'No. of Segments'; 'Samples/Segments'; 'Time/Segment (sec)'; 'df (Hz)'; 'dof'};
            app.UITable.RowName = {};
            app.SelectdofListBoxLabel = uilabel(app.CalculateTimeHistoryTab);
            app.SelectdofListBoxLabel.Text = 'Select dof';
            app.SelectdofListBox = uilistbox(app.CalculateTimeHistoryTab);
            app.ProcessingOptionsLabel = uilabel(app.CalculateTimeHistoryTab);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.RandomNumberSeedListBoxLabel = uilabel(app.CalculateTimeHistoryTab);
            app.RandomNumberSeedListBoxLabel.Text = 'Random Number Seed';
            app.RandomNumberSeedListBox = uilistbox(app.CalculateTimeHistoryTab);
            app.RandomNumberSeedListBox.Items = {'1', '2', '3', '4', '5', 'none'};
            app.RandomNumberSeedListBox.Value = '1';
            app.AddSineTonesTab = uitab(app.TabGroup);
            app.AddSineTonesTab.Title = 'Add Sine Tones';
            app.UITable_sine_tones = uitable(app.AddSineTonesTab);
            app.UITable_sine_tones.ColumnName = {'Amplitude'; 'Freq (Hz)'; 'Phase (deg)'};
            app.UITable_sine_tones.RowName = {};
            app.UITable_sine_tones.ColumnEditable = true;
            app.CalculateButton_multi = uibutton(app.AddSineTonesTab, 'push');
            app.CalculateButton_multi.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_multiPushed, true);
            app.CalculateButton_multi.Text = 'Calculate';
            app.NumberofTonesListBoxLabel = uilabel(app.AddSineTonesTab);
            app.NumberofTonesListBoxLabel.Text = {'Number '; 'of Tones'};
            app.NumberofTonesListBox = uilistbox(app.AddSineTonesTab);
            app.NumberofTonesListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofTonesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofTonesListBoxValueChanged, true);
            app.NumberofTonesListBox.Value = '1';
            app.HIghestsinefrequencyshouldbehighestPSDfrequencyLabel = uilabel(app.AddSineTonesTab);
            app.HIghestsinefrequencyshouldbehighestPSDfrequencyLabel.Text = {'HIghest sine frequency should be less than '; 'or equal to the highest PSD frequency'};
            app.AddSineSweepTab = uitab(app.TabGroup);
            app.AddSineSweepTab.Title = 'Add Sine Sweep';
            app.UITable_sweep = uitable(app.AddSineSweepTab);
            app.UITable_sweep.ColumnName = {'Freq (Hz)'; 'Accel (G)'};
            app.UITable_sweep.RowName = {};
            app.UITable_sweep.ColumnEditable = true;
            app.SweepTypeListBoxLabel = uilabel(app.AddSineSweepTab);
            app.SweepTypeListBoxLabel.Text = 'Sweep Type';
            app.SweepTypeListBox = uilistbox(app.AddSineSweepTab);
            app.SweepTypeListBox.Items = {'linear', 'log'};
            app.SweepTypeListBox.Value = 'linear';
            app.SweepDirectionListBoxLabel = uilabel(app.AddSineSweepTab);
            app.SweepDirectionListBoxLabel.Text = 'Sweep Direction';
            app.SweepDirectionListBox = uilistbox(app.AddSineSweepTab);
            app.SweepDirectionListBox.Items = {'up', 'down'};
            app.SweepDirectionListBox.Value = 'up';
            app.NumberofCoordinatesListBoxLabel = uilabel(app.AddSineSweepTab);
            app.NumberofCoordinatesListBoxLabel.Text = {'Number of '; 'Coordinates'};
            app.NumberofCoordinatesListBox = uilistbox(app.AddSineSweepTab);
            app.NumberofCoordinatesListBox.Items = {'2', '3', '4', '5', '6', '7', '8'};
            app.NumberofCoordinatesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofCoordinatesListBoxValueChanged, true);
            app.NumberofCoordinatesListBox.Value = '2';
            app.CalculateButton_sweep = uibutton(app.AddSineSweepTab, 'push');
            app.CalculateButton_sweep.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sweepPushed, true);
            app.CalculateButton_sweep.Text = 'Calculate';
            app.HIghestsinefrequencyshouldbehighestPSDfrequencyLabel_2 = uilabel(app.AddSineSweepTab);
            app.HIghestsinefrequencyshouldbehighestPSDfrequencyLabel_2.Text = {'HIghest sine sweep  frequency should be less than '; 'or equal to the highest PSD frequency'};
            app.ExportTimeHistoryStatisticsTab = uitab(app.TabGroup);
            app.ExportTimeHistoryStatisticsTab.Title = 'Export Time History & Statistics';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ExportTimeHistoryStatisticsTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ExportTimeHistoryStatisticsTab, 'text');
            app.FunctionListBoxLabel = uilabel(app.ExportTimeHistoryStatisticsTab);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.ExportTimeHistoryStatisticsTab);
            app.FunctionListBox.Items = {'Time History', 'PSD'};
            app.FunctionListBox.Value = 'Time History';
            app.SaveButton = uibutton(app.ExportTimeHistoryStatisticsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SDOFResponsetoBaseInputButton = uibutton(app.ExportTimeHistoryStatisticsTab, 'push');
            app.SDOFResponsetoBaseInputButton.ButtonPushedFcn = createCallbackFcn(app, @SDOFResponsetoBaseInputButtonPushed, true);
            app.SDOFResponsetoBaseInputButton.Text = 'SDOF Response to Base Input';
            app.ResultsTextAreaLabel = uilabel(app.ExportTimeHistoryStatisticsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ExportTimeHistoryStatisticsTab);
            app.ResultsTextArea.Editable = 'off';
            app.TheinputarraymusthavetwocolumnsfrequencyHzamplitudeunit2HzLabel = uilabel(app.PSDSynthesisUIFigure);
            app.TheinputarraymusthavetwocolumnsfrequencyHzamplitudeunit2HzLabel.Text = 'The input array must have two columns:  frequency (Hz) & amplitude (unit^2/Hz)';
            app.ReturnButton = uibutton(app.PSDSynthesisUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.ContextMenu = uicontextmenu(app.PSDSynthesisUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CalculateButton_multi.ContextMenu = app.ContextMenu;
            app.CalculateButton_sweep.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.PSDSynthesisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_psd_synth_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PSDSynthesisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PSDSynthesisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSDSynthesisUIFigure)
        end
    end
end