classdef vibrationdata_rectangular_plate_fea_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rectangularplateFEAUIFigure     matlab.ui.Figure
        RainflowButton                  matlab.ui.control.Button
        SteinbergButton                 matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ReadOnlyPanel                   matlab.ui.container.Panel
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        TotalMasslbmEditField           matlab.ui.control.EditField
        TotalMasslbmEditFieldLabel      matlab.ui.control.Label
        NodeNearestCenterEditField      matlab.ui.control.EditField
        NodeNearestCenterEditFieldLabel  matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        ReturnButton                    matlab.ui.control.Button
        ViewConstraintsButton           matlab.ui.control.Button
        ViewMeshButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeometryMaterialTab             matlab.ui.container.Tab
        PoissonRatioEditField           matlab.ui.control.EditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        ElasticModuluspsiEditField      matlab.ui.control.EditField
        ElasticModuluspsiEditFieldLabel  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        NumberofNodesLabel              matlab.ui.control.Label
        AlongWidthEditField             matlab.ui.control.EditField
        AlongWidthEditFieldLabel        matlab.ui.control.Label
        AlongLengthEditField            matlab.ui.control.EditField
        AlongLengthEditFieldLabel       matlab.ui.control.Label
        ThicknessinEditField            matlab.ui.control.EditField
        ThicknessinEditFieldLabel       matlab.ui.control.Label
        WidthinEditField                matlab.ui.control.EditField
        WidthinEditFieldLabel           matlab.ui.control.Label
        LengthinEditField               matlab.ui.control.EditField
        LengthinEditFieldLabel          matlab.ui.control.Label
        UnitListBox                     matlab.ui.control.ListBox
        UnitListBoxLabel                matlab.ui.control.Label
        GenerateMeshButton              matlab.ui.control.Button
        MassTab_2                       matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        DistibutedMassTab               matlab.ui.container.Tab
        ReadOnlyLabel_2                 matlab.ui.control.Label
        ReadOnlyLabel                   matlab.ui.control.Label
        UpdateTotalMassButton           matlab.ui.control.Button
        BarePlateMasslbmEditField       matlab.ui.control.EditField
        BarePlateMasslbmEditFieldLabel  matlab.ui.control.Label
        TotalDistributedMasslbmEditField  matlab.ui.control.EditField
        TotalDistributedMasslbmEditFieldLabel  matlab.ui.control.Label
        UniformNonstructuralMasslbmEditField  matlab.ui.control.EditField
        UniformNonstructuralMasslbmEditFieldLabel  matlab.ui.control.Label
        MassDensitylbmin3EditField      matlab.ui.control.EditField
        MassDensitylbmin3EditFieldLabel  matlab.ui.control.Label
        MassMethodListBox               matlab.ui.control.ListBox
        MassMethodListBoxLabel          matlab.ui.control.Label
        PointMassTab                    matlab.ui.container.Tab
        ResetButton                     matlab.ui.control.Button
        ApplyPointMassesButton          matlab.ui.control.Button
        MassunitlbmLabel                matlab.ui.control.Label
        PointMassesListBox              matlab.ui.control.ListBox
        NumberofPointMassesLabel        matlab.ui.control.Label
        UITablePM                       matlab.ui.control.Table
        ViewPointMassesButton           matlab.ui.control.Button
        UnconstrainedKMTab              matlab.ui.container.Tab
        CalculateKMButton               matlab.ui.control.Button
        ConstraintsTab                  matlab.ui.container.Tab
        UITableConstraints              matlab.ui.control.Table
        Constrainednodes0Free1ConstrainedLabel  matlab.ui.control.Label
        TabGroup5                       matlab.ui.container.TabGroup
        PerimeterTab                    matlab.ui.container.Tab
        BottomBCListBox                 matlab.ui.control.ListBox
        BottomBCListBoxLabel            matlab.ui.control.Label
        LeftBCListBox                   matlab.ui.control.ListBox
        LeftBCListBoxLabel              matlab.ui.control.Label
        RightBCListBox                  matlab.ui.control.ListBox
        RightBCListBoxLabel             matlab.ui.control.Label
        TopBCListBox                    matlab.ui.control.ListBox
        TopBCListBoxLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ManuallyAddNodesTab             matlab.ui.container.Tab
        AddFourcornersFixedButton       matlab.ui.control.Button
        AddFourcornersTZButton          matlab.ui.control.Button
        AddManualButton                 matlab.ui.control.Button
        ManuallyAddNodeNumberEditField  matlab.ui.control.EditField
        ManuallyAddNodeNumberEditFieldLabel  matlab.ui.control.Label
        ConstraintTypeListBox           matlab.ui.control.ListBox
        ConstraintTypeListBoxLabel      matlab.ui.control.Label
        UpdateModelViewButton           matlab.ui.control.Button
        ResetConstraintsButton          matlab.ui.control.Button
        NaturalFreqTab                  matlab.ui.container.Tab
        ModeListBox                     matlab.ui.control.ListBox
        ModeListBoxLabel                matlab.ui.control.Label
        PlotModeShapeButton             matlab.ui.control.Button
        UITable_fn                      matlab.ui.control.Table
        CalculatefnButton               matlab.ui.control.Button
        DampingTab                      matlab.ui.container.Tab
        UITable_damping                 matlab.ui.control.Table
        SaveDampingButton               matlab.ui.control.Button
        UniformQEditField               matlab.ui.control.EditField
        UniformQLabel                   matlab.ui.control.Label
        ModalDistributionListBox        matlab.ui.control.ListBox
        ModalDistributionListBoxLabel   matlab.ui.control.Label
        DampingTypeListBox              matlab.ui.control.ListBox
        DampingTypeListBoxLabel         matlab.ui.control.Label
        BaseExcitationTab               matlab.ui.container.Tab
        SetNodetoListBox                matlab.ui.control.ListBox
        SetNodetoListBoxLabel           matlab.ui.control.Label
        ResponseNodeEditField           matlab.ui.control.EditField
        ResponseNodeEditFieldLabel      matlab.ui.control.Label
        NumberofModestoIncludeListBox   matlab.ui.control.ListBox
        NumberofModestoIncludeListBoxLabel  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        PartitionMatricesTab            matlab.ui.container.Tab
        PartitionButton                 matlab.ui.control.Button
        TransmissibilityTab             matlab.ui.container.Tab
        OutputFrequencySpacingListBox   matlab.ui.control.ListBox
        OutputFrequencySpacingListBoxLabel  matlab.ui.control.Label
        TransOutputArraysReadOnlyTextArea  matlab.ui.control.TextArea
        TransOutputArraysReadOnlyTextAreaLabel  matlab.ui.control.Label
        CalculateTransmissibilityButton  matlab.ui.control.Button
        MaxEditField_trans              matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_trans              matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        FrequencyHzLimitsLabel          matlab.ui.control.Label
        SineTab                         matlab.ui.container.Tab
        SettoFundamentalFrequencyButton  matlab.ui.control.Button
        SineResponseTextArea            matlab.ui.control.TextArea
        SineResponseTextAreaLabel       matlab.ui.control.Label
        AccelGEditField                 matlab.ui.control.EditField
        AccelGEditFieldLabel            matlab.ui.control.Label
        ExcitationLabel                 matlab.ui.control.Label
        ResponsetoSineBaseInputLabel    matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.EditField
        FreqHzEditFieldLabel            matlab.ui.control.Label
        CalculateSineResponseButton     matlab.ui.control.Button
        PSDTab                          matlab.ui.container.Tab
        PSDOutputArraysReadOnlyTextArea  matlab.ui.control.TextArea
        PSDOutputArraysReadOnlyTextAreaLabel  matlab.ui.control.Label
        OutputFrequencySpacingListBox_psd  matlab.ui.control.ListBox
        OutputFrequencySpacingListBox_2Label  matlab.ui.control.Label
        SelectInputArrayListBox_psd     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label  matlab.ui.control.Label
        UpdateListButton_psd            matlab.ui.control.Button
        TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel  matlab.ui.control.Label
        text1_psd                       matlab.ui.control.Label
        listbox_method_psd              matlab.ui.control.ListBox
        CalculatePSDResponseButton      matlab.ui.control.Button
        ArbitraryTimeHistoryTab         matlab.ui.container.Tab
        ResponseResultsReadOnlyTextArea  matlab.ui.control.TextArea
        ResponseResultsReadOnlyLabel    matlab.ui.control.Label
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        CalculateBaseInputArbitrary     matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecaccelGLabel  matlab.ui.control.Label
        MDOFSRSTab                      matlab.ui.container.Tab
        ResultsTextArea_srs             matlab.ui.control.TextArea
        ResultsTextAreaLabel_srs        matlab.ui.control.Label
        SelectInputArrayListBox_srs     matlab.ui.control.ListBox
        SelectInputArrayListBox_2Label_2  matlab.ui.control.Label
        SpecificationQEditField         matlab.ui.control.NumericEditField
        SpecificationQEditFieldLabel    matlab.ui.control.Label
        UpdateListButton_srs            matlab.ui.control.Button
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel  matlab.ui.control.Label
        text1_srs                       matlab.ui.control.Label
        listbox_method_srs              matlab.ui.control.ListBox
        CalculateBaseInputMDOFSRS       matlab.ui.control.Button
        AppliedPressureTab              matlab.ui.container.Tab
        SetNodetoListBox_pressure       matlab.ui.control.ListBox
        SetNodetoListBox_2Label         matlab.ui.control.Label
        ResponseNodeEditField_pressure  matlab.ui.control.EditField
        ResponseNodeEditField_2Label    matlab.ui.control.Label
        NumberofModestoIncludeListBox_pressure  matlab.ui.control.ListBox
        NumberofModestoIncludeListBox_2Label  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        UniformFullyCorrelatedPressureTransferFunctionTab  matlab.ui.container.Tab
        TransOutputArraysReadOnlyTextArea_pressure  matlab.ui.control.TextArea
        TransOutputArraysReadOnlyTextArea_2Label  matlab.ui.control.Label
        MaxEditField_trans_pressure     matlab.ui.control.EditField
        MaxEditField_2Label_2           matlab.ui.control.Label
        MinEditField_trans_pressure     matlab.ui.control.EditField
        MinEditFieldLabel_2             matlab.ui.control.Label
        OutputFrequencySpacingListBox_pressure  matlab.ui.control.ListBox
        OutputFrequencySpacingListBox_2Label_2  matlab.ui.control.Label
        CalculateTransmissibilityButton_pressure  matlab.ui.control.Button
        FrequencyHzLimitsLabel_pressure  matlab.ui.control.Label
        PressurePSDResponseTab          matlab.ui.container.Tab
        TobeaddedinfuturerevisionLabel  matlab.ui.control.Label
        vibrationdata_rectangular_plate_fea_appver12byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        acc
        area
        bare_mass
        beta
        constraint_matrix
        constraint_matrix_added
        constraint_matrix_bottom
        constraint_matrix_left
        constraint_matrix_right
        constraint_matrix_top
        ctdof
        D
        damping_flag
        damp_Q
        damp_ratio
        dof
        dt
        dx
        dy
        E
        e1
        e2
        e3
        e4
        element_matrix
        ematrix
        emm
        f
        fig_num
        fixed
        fn
        free_dof_array
        ibc
        icenter
        imat
        iu
        ivector
        Kwd
        Kww
        L
        LU
        LV
        mass
        mass_con
        MassMethod
        mass_unc
        mesh_flag
        method
        ModeShapes
        ModeShapes_corrected
        mu
        Mwd
        Mww
        nD
        ndof_constrained
        ne
        nem
        ngw
        nmn
        node
        nodex
        nodey
        node_matrix
        number_nodes
        num_nodes_Tz_free
        nx
        ny
        part_fn
        part_ModeShapes
        part_MST
        part_omegan
        pff
        pm
        pm_nodes
        pm_num
        point_mass_matrix
        qflag
        rd
        rho
        RX_only
        RY_only
        scale
        sr
        stiff
        stiff_con
        stiff_unc
        T
        T1
        T2
        THM
        total_mass
        tracking_array
        TT
        TZ_tracking_array
        volume
        W
        y
        
    end
    
    methods (Access = private)

        function ViewConstraints(app)

            try
                 TopBCListBoxValueChanged(app, event);
                 LeftBCListBoxValueChanged(app, event);
                 RightBCListBoxValueChanged(app, event);
                 BottomBCListBoxValueChanged(app, event);
            catch
            end

            update_constraints(app);

            sz=size(app.constraint_matrix);
            
            N=sz(1);
            
            sz=size(app.node_matrix);
            
            if(max(sz)<=0)
                warndlg('node_matrix does not exist');
                return;
            end
            
  
            app.fig_num=100;
            figure(app.fig_num);
            
            if(app.iu==1)
                plot(app.node_matrix(:,1),app.node_matrix(:,2),'.');
            else
                plot(1000*app.node_matrix(:,1),1000*app.node_matrix(:,2),'.');    
            end


            title('Constraints (Tz,Rx,Ry)  0=free 1=constrained');
            %
            app.nodex=[];
            app.nodey=[];

            app.nodex=app.node_matrix(:,1);
            app.nodey=app.node_matrix(:,2);
            %
            
            %
            for i=1:N
               if(app.constraint_matrix(i,1)>0) 
                   
            %%        string=sprintf('%d',constraint_matrix(i,1));
              
                    string='';
            
                    if(app.constraint_matrix(i,2)==1)        
                        string=strcat(string,'1');
                    else
                        string=strcat(string,'0');            
                    end
                    if(app.constraint_matrix(i,3)==1)
                        string=strcat(string,'1');
                    else
                        string=strcat(string,'0');                        
                    end
                    if(app.constraint_matrix(i,4)==1)
                        string=strcat(string,'1');
                    else
                        string=strcat(string,'0');                        
                    end     
                    
                    p=app.constraint_matrix(i,1);
                    
                    if(app.iu==1)
                        text(app.nodex(p),app.nodey(p),string);
                    else
                        text(1000*app.nodex(p),1000*app.nodey(p),string);            
                    end
               end     
            end
            if(app.iu==1)
                xlabel('X (in)');
                ylabel('Y (in)');
            else
                xlabel('X (mm)');
                ylabel('Y (mm)');    
            end
            grid on;
            %
            xmax=max(app.nodex)+app.dx/2;
            ymax=max(app.nodey)+app.dy/2;
            %
            xmin=min(app.nodex)-app.dx/2;
            ymin=min(app.nodey)-app.dy/2;
            %
            
            if(app.iu==1)
                axis([xmin,xmax,ymin,ymax]);
            else
                xxmin=1000*xmin;
                xxmax=1000*xmax;
                yymin=1000*ymin;
                yymax=1000*ymax;    
                axis([xxmin,xxmax,yymin,yymax]);    
            end
                
        end    

        function reset(app)
            app.PointMassesListBox.Value='0';
            ChangePointMass(app);
            app.ModelNameEditField.Value='';
            app.UITableConstraints.Data='';
            app.acc=[];
            app.area=[];
            app.bare_mass=[];
            app.beta=[];
            app.constraint_matrix=[];
            app.constraint_matrix_added=[];
            app.constraint_matrix_bottom=[];
            app.constraint_matrix_left=[];
            app.constraint_matrix_right=[];
            app.constraint_matrix_top=[];
            app.ctdof=[];
            app.D=[];
            app.damping_flag=[];
            app.damp_Q=[];
            app.damp_ratio=[];
            app.dof=[];
            app.dt=[];
            app.dx=[];
            app.dy=[];
            app.E=[];
            app.e1=[];
            app.e2=[];
            app.e3=[];
            app.e4=[];
            app.element_matrix=[];
            app.ematrix=[];
            app.emm=[];
            app.f=[];
            app.fig_num=[];
            app.fixed=[];
            app.fn=[];
            app.free_dof_array=[];
            app.ibc=[];
            app.icenter=[];
            app.imat=[];
            app.iu=[];
            app.ivector=[];
            app.Kwd=[];
            app.Kww=[];
            app.L=[];
            app.LU=[];
            app.LV=[];
            app.mass=[];
            app.MassMethod=[];
            app.mass_unc=[];
            app.mesh_flag=[];
            app.method=[];
            app.ModeShapes=[];
            app.ModeShapes_corrected=[];
            app.mu=[];
            app.Mwd=[];
            app.Mww=[];
            app.nD=[];
            app.ndof_constrained=[];
            app.ne=[];
            app.nem=[];
            app.ngw=[];
            app.nmn=[];
            app.node=[];
            app.nodex=[];
            app.nodey=[];
            app.node_matrix=[];
            app.number_nodes=[];
            app.num_nodes_Tz_free=[];
            app.nx=[];
            app.ny=[];
            app.part_fn=[];
            app.part_ModeShapes=[];
            app.part_MST=[];
            app.part_omegan=[];
            app.pff=[];
            app.pm=[];
            app.pm_nodes=[];
            app.pm_num=[];
            app.point_mass_matrix=[];
            app.rd=[];
            app.rho=[];
            app.RX_only=[];
            app.RY_only=[];
            app.scale=[];
            app.sr=[];
            app.stiff=[];
            app.stiff_con=[];
            app.stiff_unc=[];
            app.T=[];
            app.T1=[];
            app.T2=[];
            app.THM=[];
            app.total_mass=[];
            app.tracking_array=[];
            app.TT=[];
            app.TZ_tracking_array=[];
            app.volume=[];
            app.W=[];
            app.UITable_damping.Data=[];
            app.UITable_fn.Data=[];
            app.UITablePM.Data=[];
            app.LengthinEditField.Value='';
            app.WidthinEditField.Value='';
            app.ThicknessinEditField.Value='';
            app.TotalMasslbmEditField.Value='';
            app.TotalDistributedMasslbmEditField.Value='';
            app.NodeNearestCenterEditField.Value='';
            app.y=[];
        end    

       function update_srs(app)

            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox_srs.Items=unique(sort(cell_array)); 
            try
                app.SelectInputArrayListBox_srs.Value=app.SelectInputArrayListBox_srs.Items{1};
            catch
            end
            try
                app.listbox_method_srs.Value=app.listbox_method_srs.Items{1};
            catch
            end
        end 


        function FEA_base_arbitrary(app)

            nt=length(app.THM(:,1));
            
            app.dt=mean(diff(app.THM(:,1)));
            app.sr=1/app.dt;
                 
            damp=app.damp_ratio; 

            app.node=str2num(app.ResponseNodeEditField.Value);
             
            ij=app.TZ_tracking_array(app.node);

            nidof=0;

            for j=1:length(app.ngw)
               
                if(app.ngw(j)==ij)
                    nidof=j;
                    break;
                end
                  
            end

            retdof=nidof-app.nem;

            sz=size(app.Mww);
            nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Integrating acceleration ');
           
            [dvelox]=zeros(nt,1);
             [ddisp]=zeros(nt,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Modal Transient
            %
            clear omegad;
            omegad=zeros(nff,1);
            
            
            for i=1:length(damp)
                omegad(i)=app.part_omegan(i)*sqrt(1-damp(i)^2);
            end
            %
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %%

            ffn=app.part_omegan/(2*pi);
            
            num_modes=length(ffn);
            
            if(num_modes==0)
               out1=sprintf(' Increase input data sample rate >= %8.3g',10*ffn(1));
               warndlg(out1); 
               return; 
            end
            
            num_modes=str2num(app.NumberofModestoIncludeListBox.Value);
            
            noo=num_modes;
            
            for i=1:noo
                
                if(app.sr<10*ffn(i))
                   num_modes=i-1;
                   warndlg(sprintf('\n Number of included modes = %d \n due to input data sample rate limitation. ',num_modes));   
                   break; 
                end
                
            end
            

            disp(' ');
            out1=sprintf(' sample rate = %8.4g samples/sec ',app.sr);
            disp(out1);
            
            disp(' ');
            disp('  n     fn(Hz)   damp ratio');
            
            for i=1:num_modes 
               out1=sprintf(' %d.  %8.3g  %6.3g ',i,ffn(i),damp(i)); 
               disp(out1); 
            end
            
            
            Q=app.part_ModeShapes(:,1:num_modes);
            QT=Q';
            

            ff=zeros(app.nem,1);

            fq=app.THM(:,2);
            
            QTMwd=QT*app.Mwd;
            
            for i=1:length(fq)
                ff(1:app.nem,i)=fq(i);
            end
            
            disp(' ');
            disp(' Calculating FP ');
            
            FP=-QTMwd*ff;
            
            disp(' Completed FP ')    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %
            [a1,a2,df1,df2,df3,vf1,vf2,vf3,af1,af2,af3]=...
                          ramp_invariant_filter_coefficients(num_modes,app.part_omegan,damp,app.dt);
            %
            tic
            [aa,dd]=enforced_motion_modal_transient_engine_fea_plate(FP,a1,a2,df1,df2,df3,...
                                                 vf1,vf2,vf3,af1,af2,af3,nt,num_modes,Q,retdof);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %
            %    Transformation back to xd xf
            %
            clear dT;
            clear vT;
            clear accT;
            %
            %
            
               accel(:,1)=app.THM(:,1);
            rel_disp(:,1)=app.THM(:,1);
            
            
            LD=length(ddisp);
            
            yddisp=zeros(app.nem,LD);
            forig=zeros(app.nem,LD);
            
            
            for j=1:app.nem
               
                    yddisp(j,:)=ddisp(:);
            
                    forig(j,:)=app.THM(:,2);                
            end
            
            %  T2 = identity matix
            
            disp(' Put in original order ');
            
            sz=size(app.T1);
            
            MM=zeros(sz(1),1);
            
            for i=1:sz(1)
                MM(i)=sum(app.T1(i,:));
            end
            
                   accel(:,2)=MM(retdof)*app.THM(:,2)+aa(:);
                rel_disp(:,2)=MM(retdof)*ddisp(:)+dd(:)-ddisp(:);
            
            
            if(app.iu==1)
                rel_disp(:,2)=rel_disp(:,2)*386;
            else
                rel_disp(:,2)=rel_disp(:,2)*9.81*1000;    
            end
            
            toc     
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.fig_num=30;
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            title('Base Input');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(accel(:,1),accel(:,2));
            grid on;
            xlabel('Time (sec)');
            ylabel('Accel (G)');
            out1=sprintf('Acceleration at node %d ',app.node);
            title(out1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
         
            figure(app.fig_num);
            app.fig_num=app.fig_num+1;
            plot(rel_disp(:,1),rel_disp(:,2));
            grid on;
            xlabel('Time (sec)');
            
            if(app.iu==1)
                ylabel('Rel Disp (in)');
                DQ='in';
            else
                ylabel('Rel Disp (mm)');
                DQ='mm';
            end
            out1=sprintf('Relative Displacement at node %d ',app.node);
            title(out1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            a2=accel(:,2);
            r2=rel_disp(:,2);

            ss=sprintf(' Acceleration (G) \n\n max=%7.3g   min=%7.3g \n std=%7.3g',max(a2),min(a2),std(a2));

            ss=sprintf('%s \n\n Rel Disp (%s) \n\n max=%7.3g   min=%7.3g \n std=%7.3g',ss,DQ,max(r2),min(r2),std(r2));


            output_name1=sprintf('accel_node_%d',app.node);
            output_name2=sprintf('rel_disp_node_%d',app.node);

            sq=sprintf('%s\n %s',output_name1,output_name2);
         
            app.ResponseResultsReadOnlyTextArea.Value=sprintf('%s \n\n Output arrays \n\n %s',ss,sq);
            
            assignin('base', output_name1, accel);
            assignin('base', output_name2, rel_disp);


        end        

        function update_th(app)
            CloseFigures(app);
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox.Value=app.SelectInputArrayListBox.Items{1};
            catch
            end           
            try
                app.listbox_method.Value=app.listbox_method.Items{1};
            catch
            end
        end        

        function TransMain(app)
            num_modes=str2num(app.NumberofModestoIncludeListBox.Value);
            
            app.node=str2num(app.ResponseNodeEditField.Value);

            damp=app.damp_ratio;
            
            omega=app.part_omegan;
            
            sz=size(app.part_ModeShapes);
            
            for i=1:sz(1)
                for j=1:sz(2)
                    if(isnan(app.part_ModeShapes(i,j)))
                        warndlg('ModeShape error');
                        return;
                    end
                    if(isinf(app.part_ModeShapes(i,j)))
                        warndlg('ModeShapes error');
                        return;
                    end           
                end
            end
             
            for i=1:length(omega)
                if(isnan(omega(i)))
                    warndlg('omega error');
                    return;
                end
                if(isinf(omega(i)))
                    warndlg('omega error');
                    return;
                end    
            end
            
            MST=app.part_ModeShapes';
            
            sz=size(app.Mww);
            nff=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            omegan=omega;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            
            [app.f,app.acc,app.rd]=fea_transmissibility_core(app.f,nff,app.nem,app.part_ModeShapes,MST,app.Mwd,...
                                            damp,omegan,app.TT,app.ngw,app.TZ_tracking_array,app.node,num_modes);
        end


        function ClearSine(app)
            app.SineResponseTextArea.Visible='off';
            app.SineResponseTextAreaLabel.Visible='off';
        end    

       function update_psd(app)
            CloseFigures(app);
            app.SelectInputArrayListBox_psd.Items={' '};
            [cell_array]=update_power_spectral_density_alt();
            app.SelectInputArrayListBox_psd.Items=unique(sort(cell_array));
            try
                app.SelectInputArrayListBox_psd.Value=app.SelectInputArrayListBox_psd.Items{1};
            catch
            end         
            try
                app.listbox_method_psd.Value=app.listbox_method_psd.Items{1};
            catch
            end

        end       

        function SaveTheDamping(app)
            app.UITable_damping.Visible='on';

            num=min([12 length(app.fn)]);

            app.damp_Q=zeros(num,1);
            app.damp_ratio=zeros(num,1);
           
            if(contains(app.nD,'Uniform')) % uniform    
             
                damp(1)=str2num(app.UniformQEditField.Value);
                damp(2:num)=damp(1);

                data_s = cell(num, 2);

                for i=1:num
                    data_s{i,1}=sprintf('%7.4g',app.fn(i));
                    data_s{i,2}=sprintf('%g',damp(i));
                end    

                app.UITable_damping.Data=data_s;


            else
                N=num;
            
                A=char(app.UITable_damping.Data);
                B=str2num(A);
            
                damp=B((N+1):(2*N));
            end
            
            if(contains(app.nmn,'Amplification')) % Q

                app.UITable_damping.ColumnName={'fn (Hz)';'Q'};
                
                app.damp_Q=damp;
                
                for i=1:num
                    app.damp_ratio(i)=1/(2*damp(i));
                end
            else
                app.UITable_damping.ColumnName={'fn (Hz)';'Damping Ratio'};
                
                app.damp_ratio=damp;
                
                for i=1:num
                    app.damp_Q(i)=1/(2*damp(i));
                end    
                
            end
        end

        function GetGeometry(app)

            Items = strtrim(app.UnitListBox.Items);
            im = strtrim(app.UnitListBox.Value);
            app.iu = find(strcmp(Items, im));

            app.nx=str2num(app.AlongLengthEditField.Value);
            app.ny=str2num(app.AlongWidthEditField.Value);

            app.L=str2num(app.LengthinEditField.Value);
            app.W=str2num(app.WidthinEditField.Value);
            app.T=str2num(app.ThicknessinEditField.Value);

            if(isempty(app.L))
                warndlg('Enter Geometry first, ref 2')
                return;
            end    
            if(isempty(app.W))
                warndlg('Enter Geometry first')
                return;
            end  
            if(isempty(app.T))
                warndlg('Enter Geometry first')
                return;
            end           
            
            if(app.iu==2) % metric
                app.T=app.T/1000;
                app.L=app.L/1000;
                app.W=app.W/1000;
            end

            app.area=app.L*app.W;
            app.volume=app.area*app.T;
        end    

        function update_bare_plate(app)

            GetGeometry(app);

            if(app.volume==0)
                warndlg('Volume = 0')
                return;
            end              
            if(app.rho==0)
                warndlg('rho = 0')
                return;
            end   


            app.bare_mass=app.volume*app.rho;

            if(app.bare_mass==0)
                warndlg('Bare Mass = 0')
                return;
            end   


            try
                s2=sprintf('%6.3g',app.bare_mass);
                if(app.bare_mass>0 && app.bare_mass<1.0e+20)
                    app.BarePlateMasslbmEditField.Value=s2;
                else
                    app.BarePlateMasslbmEditField.Value='0';
                end
            catch
                s2='0';
                app.BarePlateMasslbmEditField.Value=s2;
            end 

            if(app.MassMethod==1)
                app.TotalDistributedMasslbmEditField.Value=s2;
            end    
            if(app.MassMethod==2)
                nsm=str2num(app.UniformNonstructuralMasslbmEditField.Value);
                mm=app.bare_mass+nsm;
                s3=sprintf('%6.3g',mm);
                app.TotalDistributedMasslbmEditField.Value=s3;
            end   

        end

        function BCListbox(app)

            app.nx=str2num(app.AlongLengthEditField.Value);
            app.ny=str2num(app.AlongWidthEditField.Value);

            Items = strtrim(app.RightBCListBox.Items);
            im = strtrim(app.RightBCListBox.Value);
            app.e2 = find(strcmp(Items, im));

            Items = strtrim(app.BottomBCListBox.Items);
            im = strtrim(app.BottomBCListBox.Value);
            app.e1 = find(strcmp(Items, im));

            Items = strtrim(app.TopBCListBox.Items);
            im = strtrim(app.TopBCListBox.Value);
            app.e3 = find(strcmp(Items, im));

            Items = strtrim(app.LeftBCListBox.Items);
            im = strtrim(app.LeftBCListBox.Value);
            app.e4 = find(strcmp(Items, im));

        end    

        function SetResponseNode(app)

            Items = strtrim(app.SetNodetoListBox.Items);
            im = strtrim(app.SetNodetoListBox.Value);
            i = find(strcmp(Items, im));
        
            app.ResponseNodeEditField.Value=app.NodeNearestCenterEditField.Value;
   
            if(i>=2)
                try
                    B=app.ModeShapes_corrected(1:3:end,i-1);
                    [~,I]=max(abs(B));
                    app.ResponseNodeEditField.Value=sprintf('%d',I);
                catch
                end
            end

            clear i;

            Items = strtrim(app.SetNodetoListBox_pressure.Items);
            im = strtrim(app.SetNodetoListBox_pressure.Value);
            i = find(strcmp(Items, im));
        
            app.ResponseNodeEditField_pressure.Value=app.NodeNearestCenterEditField.Value;
   
            if(i>=2)
                try
                    B=app.ModeShapes_corrected(1:3:end,i-1);
                    [~,I]=max(abs(B));
                    app.ResponseNodeEditField_pressure.Value=sprintf('%d',I);
                catch
                end
            end

        end    

        function update_total_mass(app)

            app.pm_num=str2num(app.PointMassesListBox.Value);


            try
                A=app.UITablePM.Data;
                B=A;
            catch
                warndlg('UITablePM error');
                return;
            end           

            N=str2num(app.PointMassesListBox.Value);

            B=str2num(char(B));

            if(N>=1)
                try
                    app.pm_nodes=B(1:N);
                    app.pm=B((N+1):(2*N)); 
        
                    app.point_mass_matrix=[app.pm_nodes app.pm];
        
                    spm=0;
                    if(N>=1)
                        spm=sum(app.pm);
                    end
    
                catch
                    % warndlg('PM failed');
                end
            end


            tdm=str2num(app.TotalDistributedMasslbmEditField.Value);

            tm=tdm;

            try
                tm=spm+tm;
            catch
            end

            app.TotalMasslbmEditField.Value=sprintf('%6.3g',tm);


        end

        function update_all_masses(app)

            if(app.MassMethod~=3)
                update_bare_plate(app);
            end

            update_total_mass(app);            
        end    

        function ChangePointMass(app)
            Clearfn(app);

            app.pm_num=str2num(app.PointMassesListBox.Value);

            N=app.pm_num;
           
            data_s = cell(N, 2);
 
            app.UITablePM.Data=data_s; 
        end

        function ChangeMassMethod_start(app)
            Clearfn(app);
            app.TotalMasslbmEditField.Value=' ';

            Items = strtrim(app.MassMethodListBox.Items);
            im = strtrim(app.MassMethodListBox.Value);
            app.MassMethod = find(strcmp(Items, im));

            n=app.MassMethod;

            app.MassDensitylbmin3EditField.Visible='off';
            app.MassDensitylbmin3EditFieldLabel.Visible='off';

            app.UniformNonstructuralMasslbmEditField.Visible='off';
            app.UniformNonstructuralMasslbmEditFieldLabel.Visible='off';

            app.TotalDistributedMasslbmEditField.Editable='off';
        
            if(n<=2)
                app.BarePlateMasslbmEditField.Visible='on';
                app.BarePlateMasslbmEditFieldLabel.Visible='on';
                app.MassDensitylbmin3EditField.Visible='on';
                app.MassDensitylbmin3EditFieldLabel.Visible='on';
                app.ReadOnlyLabel.Visible='on'; 
                app.ReadOnlyLabel_2.Visible='on'; 
            end
            
            if(n==2)
                app.UniformNonstructuralMasslbmEditField.Visible='on';
                app.UniformNonstructuralMasslbmEditFieldLabel.Visible='on';
            else
                app.UniformNonstructuralMasslbmEditField.Value='0'; 
            end
            if(n==3)
                app.BarePlateMasslbmEditField.Visible='off';
                app.BarePlateMasslbmEditFieldLabel.Visible='off';
                app.ReadOnlyLabel.Visible='off';
                app.ReadOnlyLabel_2.Visible='off';

                app.TotalDistributedMasslbmEditField.Editable='on';
            end
        end    
        function ChangeMassMethod(app)

            ChangeMassMethod_start(app)
            
            update_all_masses(app);

        end

        function Clearfn(app)
            app.UITable_fn.Data=[];
            app.fn=[];
            app.PlotModeShapeButton.Enable='off';
        end    

        function update_constraints(app)

            BCListbox(app);

            app.constraint_matrix=[ app.constraint_matrix_right;...
                                app.constraint_matrix_left; ...
                                app.constraint_matrix_top;  ...
                                app.constraint_matrix_bottom; ... 
                                app.constraint_matrix_added];
            app.constraint_matrix=sortrows(app.constraint_matrix,1);

            
            iflag=1;
            
            while(iflag==1)
                
                iflag=0;
                
                N=length(app.constraint_matrix(:,1));
            
                for i=1:N
                    if(app.constraint_matrix(i,1)==0)
                        app.constraint_matrix(i,:)=[];
                        iflag=1;
                        break;
                    end
                end
            
            end
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % combine
            
            for ijk=1:5
            
                sz=size(app.constraint_matrix);
            
                N=sz(1);
            
                for i=1:N-1
                    for j=i+1:N
                        if(app.constraint_matrix(i,1)==app.constraint_matrix(j,1))
                            for k=2:4
                                an=app.constraint_matrix(i,k)+app.constraint_matrix(j,k);
                                if(an>1)
                                    an=1;
                                end
                                app.constraint_matrix(i,k)=an;
                                app.constraint_matrix(j,k)=an;
                            end
                        end    
                    end    
                end    
            
            end
            
            app.constraint_matrix=unique(app.constraint_matrix,'rows');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 
            sz=size(app.constraint_matrix);
            N=sz(1);
            
            for i=1:N
                string_f{i}=sprintf(' %d %d %d %d',app.constraint_matrix(i,1),...
                                                   app.constraint_matrix(i,2),...
                                                   app.constraint_matrix(i,3),...
                                                   app.constraint_matrix(i,4));
            end   

            if(N>=1)
                ss = cell(N, 4);

                for i=1:N
                    ss{i,1}=app.constraint_matrix(i,1);
                    ss{i,2}=app.constraint_matrix(i,2);
                    ss{i,3}=app.constraint_matrix(i,3);
                    ss{i,4}=app.constraint_matrix(i,4);
                end

                app.UITableConstraints.Data=ss;

            else
                app.UITableConstraints.Data='';     
            end
            
            app.ematrix=[app.e1 app.e2 app.e3 app.e4];

        end    

        function PlotMesh(app)
            h=figure(200);
            if(app.iu==1)
                plot(app.node_matrix(:,1),app.node_matrix(:,2),'.');
            else
                plot(1000*app.node_matrix(:,1),1000*app.node_matrix(:,2),'.');    
            end
            %
            if(app.iu==1)
                xlabel('X (in)');
                ylabel('Y (in)');
            else
                xlabel('X (mm)');
                ylabel('Y (mm)');    
            end
            title('Nodes & Elements');
            grid on;
            %
            app.nodex=[];
            app.nodey=[];

            app.nodex=app.node_matrix(:,1);
            app.nodey=app.node_matrix(:,2);
            
            %
            for i=1:app.number_nodes
               string=num2str(i,'%d\n');
               if(app.iu==1)
                    text(app.nodex(i),app.nodey(i),string);
               else
                    text(1000*app.nodex(i),1000*app.nodey(i),string);       
               end
            end
            %
            xmax=max(app.nodex)+app.dx/2;
            ymax=max(app.nodey)+app.dy/2;
            %
            xmin=min(app.nodex)-app.dx/2;
            ymin=min(app.nodey)-app.dy/2;
            %
            if(app.iu==1)
                axis([xmin,xmax,ymin,ymax]);
            else
                xxmin=xmin*1000;
                xxmax=xmax*1000;    
                yymin=ymin*1000;
                yymax=ymax*1000;       
                axis([xxmin,xxmax,yymin,yymax]);    
            end    
            %
            node1=app.element_matrix(:,1);
            node2=app.element_matrix(:,2);
            node3=app.element_matrix(:,3);
            node4=app.element_matrix(:,4);
            %
            hold on;
            %
            for i=1:app.ne
                x=[ app.nodex(node1(i)) app.nodex(node2(i)) app.nodex(node3(i)) app.nodex(node4(i)) app.nodex(node1(i))];
                yp=[ app.nodey(node1(i)) app.nodey(node2(i)) app.nodey(node3(i)) app.nodey(node4(i)) app.nodey(node1(i))];
            
                if(app.iu==1)
                    plot(x,yp);    
                else
                    xx=1000*x;
                    yy=1000*yp;
                    plot(xx,yy);        
                end
            end
            hold off;
            %         
            savefig('Mesh.fig')
        end    

        function ChangeUnits(app)

            Clearfn(app);

            Items = strtrim(app.UnitListBox.Items);
            im = strtrim(app.UnitListBox.Value);
            app.iu = find(strcmp(Items, im));

            if(app.iu==1)
                app.LengthinEditFieldLabel.Text='Length (in)';
                app.WidthinEditFieldLabel.Text='Width (in)';
                app.ThicknessinEditFieldLabel.Text='Thickness (in)';
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in)^3';
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Nonstructural Mass (lbm)';
                app.BarePlateMasslbmEditFieldLabel.Text='Bare Plate Mass (lbm)';
                app.TotalDistributedMasslbmEditFieldLabel.Text='Total Distributed Mass (lbm)';
                app.TotalMasslbmEditFieldLabel.Text='Total Mass (lbm)';
                app.MassunitlbmLabel.Text='Mass unit (lbm)';
                % app.inchLabel.Text='inch';
            else
                app.LengthinEditFieldLabel.Text='Length (mm)';
                app.WidthinEditFieldLabel.Text='Width (mm)';
                app.ThicknessinEditFieldLabel.Text='Thickness (mm)';
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/in)^3';
                app.UniformNonstructuralMasslbmEditFieldLabel.Text='Nonstructural Mass (kg)';
                app.BarePlateMasslbmEditFieldLabel.Text='Bare Plate Mass (kg)';
                app.TotalDistributedMasslbmEditFieldLabel.Text='Total Distributed Mass (kg)';
                app.TotalMasslbmEditFieldLabel.Text='Total Mass (kg)';  
                app.MassunitlbmLabel.Text='Mass unit (kg)';
                % app.inchLabel.Text='m';   
            end    

            if(app.iu==1)
                app.scale=386;
                app.LU='in';
                app.LV='ips';
            else
                app.scale=9.81;
                app.LU='m';
                app.LV='m/sec';
            end    
            
            Clearfn(app); 
            ChangeMaterials(app);
        end    

        function ChangeMaterials(app)
            
            Clearfn(app);
            
            app.E=0;
            app.rho=0;            

            Items = char(app.MaterialListBox.Items);
            im=app.MaterialListBox.Value;
            [app.imat]=find_index(Items,im);            

            [app.E,app.rho,app.mu]=six_materials(app.iu,app.imat);

            app.PoissonRatioEditField.Value=sprintf('%g',app.mu);
            
            app.ElasticModuluspsiEditField.Value=sprintf('%g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%g',app.rho);  

            if(app.iu==1)
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (lbm/in^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (psi)';   
                % app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (lbm)';
                % app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (in)';
                % app.PointMasslbmEditFieldLabel.Text='Point Mass (lbm)';
            else
                app.MassDensitylbmin3EditFieldLabel.Text='Mass Density (kg/m^3)';
                app.ElasticModuluspsiEditFieldLabel.Text='Elastic Modulus (GPa)'; 
                % app.UniformNonstructuralMasslbmEditFieldLabel.Text='Uniform Nonstructural Mass (kg)';
                % app.PointMassLocationfromLeftEndinEditFieldLabel.Text='Point Mass Location from Left End (m)';
                % app.PointMasslbmEditFieldLabel.Text='Point Mass (kg)';         
            end    

            app.ElasticModuluspsiEditField.Value=sprintf('%8.4g',app.E);
            app.MassDensitylbmin3EditField.Value=sprintf('%8.4g',app.rho);        

        end        
        
        function CloseFigures(app)

            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end

        function ConstraintReset(app)
           
            app.TopBCListBox.Value='free';
            app.BottomBCListBox.Value='free';
            app.LeftBCListBox.Value='free';
            app.RightBCListBox.Value='free';

            app.constraint_matrix_added=[];

            app.constraint_matrix_top=zeros(1,4);
            app.constraint_matrix_left=zeros(1,4);
            app.constraint_matrix_bottom=zeros(1,4);
            app.constraint_matrix_right=zeros(1,4);
            
            app.constraint_matrix_added=zeros(1,4);
            
            app.constraint_matrix=zeros(1,4);
            app.ematrix=zeros(1,4);

            app.UITableConstraints.Data='';
            
            update_constraints(app);
        end

       function ChangeDamping(app)

            app.nmn=app.DampingTypeListBox.Value;
            app.nD=app.ModalDistributionListBox.Value;

            N=min([12 length(app.fn)]);

            data_s = cell(N, 2);
            
            for i=1:N
                data_s{i,1}=sprintf('%7.4g',app.fn(i));
            end    

            if(contains(app.nD,'Uniform'))

                app.UITable_damping.Visible='off';

                app.UniformQEditField.Visible='on';
                app.UniformQLabel.Visible='on';

                if(contains(app.nmn,'Amplification')) 
                    app.UniformQLabel.Text='Uniform Q';
                    app.UITable_damping.ColumnName={'fn (Hz)';'Q'};
                else  
                    app.UniformQLabel.Text='Uniform Damping Ratio';
                    app.UITable_damping.ColumnName={'fn (Hz)';'Damping Ratio'};   
                end

                for i=1:N
                    data_s{i,2}=app.UniformQEditField.Value;
                end    

            else

                app.UITable_damping.Visible='on';

                app.UniformQEditField.Visible='off';
                app.UniformQLabel.Visible='off';

                if(contains(app.nmn,'Amplification')) 
                    app.UITable_damping.ColumnName={'fn (Hz)';'Q'};
                else  
                    app.UITable_damping.ColumnName={'fn (Hz)';'Damping Ratio'};               
                end   

                for i=1:N
                    data_s{i,2}='';
                end 

            end
            app.UITable_damping.Data=data_s;
            app.UITable_damping.ColumnEditable = true;
            
        end                    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reset(app);

            app.fig_num=1;

            app.volume=0;
            app.area=0;
            app.L=0;
            app.W=0;
            app.T=0;

            update_psd(app);
            update_th(app);
            update_srs(app);

            CloseFigures(app);
            ChangeUnits(app);
            ChangeMassMethod_start(app);
            ChangePointMass(app);
            ChangeDamping(app);

            app.mesh_flag=0;
            app.damping_flag=0;

            app.NodeNearestCenterEditField.Value=sprintf(' ');
            app.NodeNearestCenterEditField.Editable='off';
            app.TotalMasslbmEditField.Value=' ';
            app.TotalMasslbmEditField.Editable='off';
            app.BarePlateMasslbmEditField.Editable='off';

            app.UITablePM.ColumnWidth={62,63};
            app.UITablePM.ColumnEditable = [true true];

            app.UITable_fn.ColumnWidth={75,96,107};
            app.UITable_fn.ColumnEditable = [false false false];

            app.UITableConstraints.ColumnWidth={54,35,35,35};

            app.pm_num=[];
            app.pm_nodes=[];
            app.pm=[];

            app.fixed=[1 1 1]; 
            app.RY_only=[1 0 1];   
            app.RX_only=[1 1 0];
    
            ConstraintReset(app);

            app.ModelNameEditField.Visible='off';
            app.ModelNameEditFieldLabel.Visible='off';

            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_damping,s) % adjust the style
            addStyle(app.UITable_fn,s) % adjust the style
            addStyle(app.UITableConstraints,s) % adjust the style            

        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged(app, event)
            ChangeMaterials(app);
            
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            ChangeUnits(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)

            CloseFigures(app);
            
            app.fig_num=1;
            
            GetGeometry(app);
            

            h=app.T;
              
            app.T=[];
            
            app.nx=str2num(app.AlongLengthEditField.Value);
            app.ny=str2num(app.AlongWidthEditField.Value);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.number_nodes=app.nx*app.ny;
            %
            app.dx=app.L/(app.nx-1);
            app.dy=app.W/(app.ny-1);

            %
            
            ijk=1;
            
            app.node_matrix=zeros(app.nx*app.ny,2);
            
            dcmax=1.0e+99;
            app.icenter=1;
            
            % disp(' Calculate node_matrix ');
            
            %
            for j=1:app.ny
            %    
                for i=1:app.nx
                    app.node_matrix(ijk,1)=(i-1)*app.dx;
                    app.node_matrix(ijk,2)=(j-1)*app.dy;      
                    
                    aa=(app.L/2)-app.node_matrix(ijk,1);
                    bb=(app.W/2)-app.node_matrix(ijk,2);
                    
                    dc=sqrt( aa^2 + bb^2);
                    
                    if(dc<dcmax)
                        dcmax=dc;
                        app.icenter=ijk;
                    end
                    
                    ijk=ijk+1;  
                end
            %    
            end
            
            %
            app.ne=(app.nx-1)*(app.ny-1);
            %
            j=1;
            k=1;
            
            % disp(' Calculate element_matrix ');
            
            for i=1:app.ne
            %
                app.element_matrix(i,1)=j; 
                app.element_matrix(i,2)=j+1; 
                app.element_matrix(i,3)=app.element_matrix(i,2)+app.nx;
                app.element_matrix(i,4)=app.element_matrix(i,1)+app.nx;
            %
                j=j+1;
                k=k+1;
                
                if(k==app.nx)
                    j=j+1;
                    k=1;
                end
            %    
            end
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            % disp(' Plot node_matrix ');
            
            PlotMesh(app);

            %
            app.dof=app.number_nodes*3;
            
            n=app.icenter;
            app.ctdof=(n-1)*3+1;

            app.NodeNearestCenterEditField.Value=sprintf('%d',app.icenter);
            
            % out1=sprintf('  Node nearest center = %d \n   with translational dof = %d ',app.icenter,app.ctdof);
            % disp(out1);
           
            app.mesh_flag=1;

        end

        % Button pushed function: ViewMeshButton
        function ViewMeshButtonPushed(app, event)
            PlotMesh(app);

        end

        % Value changed function: MassMethodListBox
        function MassMethodListBoxValueChanged(app, event)
            
            ChangeMassMethod(app);
            
        end

        % Button pushed function: CalculatefnButton
        function CalculatefnButtonPushed(app, event)
            
            GetGeometry(app);
            
            if(app.T==0 )
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end    
            if(app.L==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end   
            if(app.W==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end 
            if(app.mesh_flag==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;                
            end    

            update_all_masses(app);

            app.mass=app.mass_unc;
            app.stiff=app.stiff_unc;
            sz=size(app.mass_unc);
            app.dof=sz(1);
            
            app.ibc=0;
        
            try  
                n=length(app.constraint_matrix(:,1));   
                
                for i=1:n
                    if(app.constraint_matrix(i,1)>0)
                        app.ibc=app.ibc+1;
                    end
                end
            catch    
            end
        
        % fprintf('\n number of constrained nodes = %d \n\n',ibc);

        uflag=0;
        if(app.ibc>=1 && app.constraint_matrix(1,1) ~= 0)
        
            [app.mass,app.stiff,pp,con,qicenter,qctdof,app.ivector]=...
                rectangular_plate_apply_constraints_c(app.constraint_matrix,app.ibc,app.mass,app.stiff,app.icenter,app.ctdof);
            uflag=1;
        end
        
        sz=size(app.mass);
        app.ndof_constrained=sz(1);
        
        
        % disp(' Calculate eigenvalues...  ');
        % disp(' ');
        
        tic
        [app.fn,~,app.ModeShapes,~]=Generalized_Eigen(app.stiff,app.mass,2);
        toc

        app.mass_con=app.mass;
        app.stiff_con=app.stiff;
        
        % disp(' ');
        % disp(' Finished eigenvalue calculation ');

        tmm=str2num(app.TotalMasslbmEditField.Value);

        if(app.iu==1)
            tmm=tmm/386;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        if(app.ibc>=1 && app.constraint_matrix(1,1) ~= 0)
            [app.ModeShapes_corrected]=mode_shape_correction(app.dof,pp,con,app.ModeShapes);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        if(uflag==1)
        
            LM=app.ModeShapes'*app.mass*app.ivector;
                
            pf=LM;
        %    
            mmm=app.ModeShapes'*app.mass*app.ModeShapes;   
        %  
            clear length;
            nndd=length(pf);
            
            app.pff=zeros(nndd,1);
            app.emm=zeros(nndd,1);
            
            for i=1:nndd
                app.pff(i)=pf(i)/mmm(i,i);
                app.emm(i)=pf(i)^2/mmm(i,i);
                if(app.emm(i) < 1e-30)
                    app.emm(i)=0.;
                    app.pff(i)=0.;
                end    
            end
            pdof=length(app.emm);   
        else
            pdof=length(app.fn);
        end
        %
        if(pdof>40)
            pdof=40;
        end 
        
        %
            if(uflag==1)
        
                % fprintf('\n  tmm=%g \n',tmm);
            
                % disp(' ');
                % disp('                                        Effective            ');
                % disp('           Natural     Participation    Modal Mass   Mass    ');
                
                % if(app.iu==1)
                %    disp('Mode       Freq(Hz)       Factor          (lbm)      Percent ');
                % else
                %    disp('Mode       Freq(Hz)       Factor          (kg)      Percent ');        
                % end
            %
                sss=0;

                frac=zeros(pdof,1);
                
                for i=1:pdof
                    frac(i)=100*app.emm(i)/tmm;
                    if(frac(i)<0.0001)
                        frac(i)=0;
                    end
                    % if(app.iu==1)
                    %     out1 = sprintf('  %d \t %11.5g \t %9.5f \t %9.5f \t %7.3f',i,app.fn(i),app.pff(i),386*app.emm(i),frac);
                    % else
                    %    out1 = sprintf('  %d \t %11.5g \t %9.5f \t %9.5f \t %7.3f',i,app.fn(i),app.pff(i),app.emm(i),frac);            
                    % end
                    % disp(out1)
                        
                    sss=sss+app.emm(i);
                    
                    if(i==24)
                        break;
                    end
                end        
            %
                % disp(' ')
                
                if(app.iu==1)
                    out1 = sprintf(' Total Effective Modal Mass = %6.3g lbf sec^2/in',sss );
                    out2 = sprintf('                            = %6.3g lbm',sss*386 );
                else
                    out1 = sprintf(' Total Effective Modal Mass = %6.3g kg',sss );        
                end
                
            else
                app.ModeShapes_corrected=app.ModeShapes;
            end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % set(handles.uipanel_plot_modes,'Visible','on');

        n=min([12 length(app.fn)]);

        ss = cell(n, 3);
        rr = cell(n, 1);

        for i=1:n
            ss{i,1}=sprintf('%7.4g',app.fn(i));
            ss{i,2}=sprintf('%7.3g',app.pff(i));
            ss{i,3}=sprintf('%7.3g',frac(i));
            rr{i}=sprintf('%d',i);
        end

        app.UITable_fn.Visible='on';
        app.UITable_fn.Data=ss;
        app.UITable_fn.RowName=rr;

        % try
        %     out1=sprintf('\n Constrained translational dof nearest center = %d  \n',qctdof);
        %     disp(out1);
        % catch
        % end

        app.PlotModeShapeButton.Enable='on';
        
        msgbox('fn Calculation Complete');

        end

        % Button pushed function: ViewPointMassesButton
        function ViewPointMassesButtonPushed(app, event)
            
            app.pm_num=str2num(app.PointMassesListBox.Value);
            
            A=char(app.UITablePM.Data);
            
            B=str2num(A);

            N=app.pm_num;
            
            app.pm_nodes=B(1:N);
            app.pm=B((N+1):(2*N)); 

            app.point_mass_matrix=[app.pm_nodes app.pm];


            sz=size(app.point_mass_matrix);
            
            N=sz(1);
            
            sz=size(app.node_matrix);
            
            if(max(sz)<=0)
                warndlg('node_matrix does not exist');
                return;
            end
            
            app.fig_num=90;
            h=figure(app.fig_num);
            
            if(app.iu==1)
                plot(app.node_matrix(:,1),app.node_matrix(:,2),'.');
            else
                plot(1000*app.node_matrix(:,1),1000*app.node_matrix(:,2),'.');    
            end
            
            %
            clear nodex;
            clear nodey;
            app.nodex=app.node_matrix(:,1);
            app.nodey=app.node_matrix(:,2);
            %
            
            %
            for i=1:N
               if(app.point_mass_matrix(i,1)>0) 
                   
                    string=sprintf('%d, %g',app.point_mass_matrix(i,1),app.point_mass_matrix(i,2));
              
                    p=app.point_mass_matrix(i,1);
                    
                    if(app.iu==1)
                        text(app.nodex(p),app.nodey(p),string);
                    else
                        text(1000*app.nodex(p),1000*app.nodey(p),string);            
                    end
                        
               end     
            end
            if(app.iu==1)
                title('Point Masses (node, lbm)');
                xlabel('X (in)');
                ylabel('Y (in)');
            else
                title('Point Masses (node, kg)');    
                xlabel('X (mm)');
                ylabel('Y (mm)');    
            end
            grid on;
            %
            xmax=max(app.nodex)+app.dx/2;
            ymax=max(app.nodey)+app.dy/2;
            %
            xmin=min(app.nodex)-app.dx/2;
            ymin=min(app.nodey)-app.dy/2;
            %
            axis([xmin,xmax,ymin,ymax]);
            
            %
            xmax=max(app.nodex)+app.dx/2;
            ymax=max(app.nodey)+app.dy/2;
            %
            xmin=min(app.nodex)-app.dx/2;
            ymin=min(app.nodey)-app.dy/2;
            %
            if(app.iu==1)
                axis([xmin,xmax,ymin,ymax]);
            else
                xxmin=xmin*1000;
                xxmax=xmax*1000;    
                yymin=ymin*1000;
                yymax=ymax*1000;       
                axis([xxmin,xxmax,yymin,yymax]);    
            end    
            %
 
        end

        % Value changed function: PointMassesListBox
        function PointMassesListBoxValueChanged(app, event)
            ChangePointMass(app);
        end

        % Button pushed function: ApplyPointMassesButton
        function ApplyPointMassesButtonPushed(app, event)
            update_all_masses(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PointMassesListBox.Value='0';

            N=0;
           
            data_s = cell(N, 2);
 
            app.UITablePM.Data=data_s;           
        end

        % Button down function: MassTab_2
        function MassTab_2ButtonDown(app, event)
            
            GetGeometry(app);
            
            if(isempty(app.L))
                warndlg('Enter Geometry first, ref 3')
                return;
            end    
            if(isempty(app.W))
                warndlg('Enter Geometry first')
                return;
            end  
            if(isempty(app.T))
                warndlg('Enter Geometry first')
                return;
            end     
            if(app.mesh_flag==0)
                GenerateMeshButtonPushed(app, event);
            end 
        end

        % Value changing function: AlongLengthEditField
        function AlongLengthEditFieldValueChanging(app, event)
            app.mesh_flag=0;
            Clearfn(app);
        end

        % Value changing function: AlongWidthEditField
        function AlongWidthEditFieldValueChanging(app, event)

            app.mesh_flag=0;
            Clearfn(app);
        end

        % Value changing function: LengthinEditField
        function LengthinEditFieldValueChanging(app, event)
            app.mesh_flag=0;
            Clearfn(app);
        end

        % Value changing function: WidthinEditField
        function WidthinEditFieldValueChanging(app, event)
            
            app.mesh_flag=0;
            Clearfn(app);
        end

        % Value changed function: ThicknessinEditField
        function ThicknessinEditFieldValueChanged(app, event)
            
            app.mesh_flag=0;
            Clearfn(app);
        end

        % Button down function: NaturalFreqTab
        function NaturalFreqTabButtonDown(app, event)
            if(app.T==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end    
            if(app.L==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end  
            if(app.W==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end     
            if(app.mesh_flag==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end
            if(isempty(app.mass_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.stiff_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    

            disp('ref 7')

            app.fn

            if(isempty(app.fn))
               app.UITable_fn.Visible='off';        
               app.PlotModeShapeButton.Enable='off';
               disp('ref 8')
            else
                app.UITable_fn.Visible='on';
                app.PlotModeShapeButton.Enable='on';
            end
        end

        % Button pushed function: UpdateTotalMassButton
        function UpdateTotalMassButtonPushed(app, event)
            Items = strtrim(app.MassMethodListBox.Items);
            im = strtrim(app.MassMethodListBox.Value);
            app.MassMethod = find(strcmp(Items, im));
            
            update_all_masses(app);
        end

        % Size changed function: MassTab_2
        function MassTab_2SizeChanged(app, event)

        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)

            PlateFEA.pff=app.pff;

            PlateFEA.num_nodes_Tz_free=app.num_nodes_Tz_free;
            PlateFEA.UITableConstraints=app.UITableConstraints.Data;

            PlateFEA.TBC=app.TopBCListBox.Value;
            PlateFEA.BBC=app.BottomBCListBox.Value;        
            PlateFEA.LBC=app.LeftBCListBox.Value;
            PlateFEA.RBC=app.RightBCListBox.Value;

            PlateFEA.ivector=app.ivector;
            PlateFEA.emm=app.emm;
            PlateFEA.ibc=app.ibc;
            PlateFEA.ModeShapes_corrected=app.ModeShapes_corrected;
            PlateFEA.fn=app.fn;
            PlateFEA.stiff=app.stiff;
            PlateFEA.mass=app.mass;
            PlateFEA.stiff_con=app.stiff_con;

            try
                PlateFEA.mass_con=app.mass_con;
            catch
            end    

            PlateFEA.ModeShapes=app.ModeShapes;

            PlateFEA.damptable=app.UITable_damping;
            PlateFEA.nmn=app.nmn;
            PlateFEA.nD=app.nD;

            PlateFEA.D=app.D;
            PlateFEA.nx=app.nx;
            PlateFEA.number_nodes=app.number_nodes;
            PlateFEA.dx=app.dx;
            PlateFEA.dy=app.dy;
            PlateFEA.node_matrix=app.node_matrix;
            PlateFEA.icenter=app.icenter;
            PlateFEA.ne=app.ne;           
            PlateFEA.element_matrix=app.element_matrix;
            PlateFEA.dof=app.dof;
            PlateFEA.mesh_flag=app.mesh_flag;

            PlateFEA.unit=app.UnitListBox.Value;
            PlateFEA.mat=app.MaterialListBox.Value;
            PlateFEA.massmethod=app.MassMethodListBox.Value;

            PlateFEA.L=app.LengthinEditField.Value;
            PlateFEA.W=app.WidthinEditField.Value;
            PlateFEA.T=app.ThicknessinEditField.Value;
       
            PlateFEA.nx=app.AlongLengthEditField.Value;
            PlateFEA.ny=app.AlongWidthEditField.Value;
            
            PlateFEA.mu=app.PoissonRatioEditField.Value;
            
            PlateFEA.E=app.ElasticModuluspsiEditField.Value;
            PlateFEA.rho=app.MassDensitylbmin3EditField.Value;       

            PlateFEA.nsm=app.UniformNonstructuralMasslbmEditField.Value;
            PlateFEA.tdm=app.TotalDistributedMasslbmEditField.Value;
            PlateFEA.baremass=app.BarePlateMasslbmEditField.Value;

            PlateFEA.stiff_unc=app.stiff_unc;
            PlateFEA.mass_unc=app.mass_unc;

            PlateFEA.constraint_matrix_top=app.constraint_matrix_top;
            PlateFEA.constraint_matrix_left=app.constraint_matrix_left;
            PlateFEA.constraint_matrix_bottom=app.constraint_matrix_bottom;
            PlateFEA.constraint_matrix_right=app.constraint_matrix_right;
            PlateFEA.constraint_matrix_added=app.constraint_matrix_added;
            PlateFEA.constraint_matrix=app.constraint_matrix;
            PlateFEA.ematrix=app.ematrix;

            PlateFEA.UQ=app.UniformQEditField.Value;
            PlateFEA.damp_Q=app.damp_Q;
            PlateFEA.damp_ratio=app.damp_ratio;
            PlateFEA.damping_flag=app.damping_flag;

            PlateFEA.damp_table=app.UITable_damping.Data;
            PlateFEA.fn_table=app.UITable_fn.Data;

            PlateFEA.dof=app.dof;
            PlateFEA.tracking_array=app.tracking_array;
            PlateFEA.TZ_tracking_array=app.TZ_tracking_array;
            PlateFEA.ngw=app.ngw;
            PlateFEA.nem=app.nem;
            PlateFEA.TT=app.TT;
            PlateFEA.T1=app.T1;
            PlateFEA.T2=app.T2;
            PlateFEA.Mwd=app.Mwd;
            PlateFEA.Mww=app.Mww;
            PlateFEA.Kwd=app.Kwd;
            PlateFEA.Kww=app.Kww;
            PlateFEA.part_ModeShapes=app.part_ModeShapes;
            PlateFEA.part_omegan=app.part_omegan;

            PlateFEA.pm_nodes=app.pm_nodes;
            PlateFEA.pm_num=app.pm_num;
            PlateFEA.point_mass_matrix=app.point_mass_matrix;
            PlateFEA.UITablePM=app.UITablePM.Data;       
            PlateFEA.NPV=app.PointMassesListBox.Value;

            % % %

            structnames = fieldnames(PlateFEA, '-full'); % fields in the struct
            
            % % %
            
               [writefname, writepname] = uiputfile('*.mat','Save data as');
            
               %%%    writepfname = fullfile(writepname, writefname);
                
            %%%    pattern = '.mat';
            %%%    replacement = '';
            %%%    sname=regexprep(writefname,pattern,replacement);
               
                elk=sprintf('%s%s',writepname,writefname);
            
                try       
                    save(elk, 'PlateFEA');
                    app.ModelNameEditField.Value=writefname;
                    app.ModelNameEditField.Visible='on';
                    app.ModelNameEditFieldLabel.Visible='on';
                catch
                    warndlg('Save error');
                    return;
                end
 
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)

            try
                [filename, pathname] = uigetfile('*.mat');
             
                NAME = [pathname,filename];
            
                app.ModelNameEditField.Value=filename;
            
                struct=load(NAME);
                structnames = fieldnames(struct, '-full'); % fields in the struct
            catch
                return;
            end

            k=length(structnames);
            
            for i=1:k
                namevar=strcat('struct.',structnames{i});
                value=eval(namevar);
                assignin('base',structnames{i},value);
            end
            
            % struct
            
            try
               PlateFEA=evalin('base','PlateFEA');
            catch
               warndlg(' evalin failed ');
               return;
            end

            reset(app);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            CloseFigures(app);

            progressbar;

            try
                app.pff=PlateFEA.pff;
            catch
            end

            try
                app.num_nodes_Tz_free=PlateFEA.num_nodes_Tz_free;
            catch
            end    

            try
                app.dof=PlateFEA.dof;
            catch
            end
            try
                app.tracking_array=PlateFEA.tracking_array;
            catch
            end          
            try
                app.TZ_tracking_array=PlateFEA.TZ_tracking_array;
            catch
            end
            try
               app.ngw= PlateFEA.ngw;
            catch
            end
            try
                app.nem=PlateFEA.nem;
            catch
            end
            try
                app.TT=PlateFEA.TT;
            catch
            end         

            progressbar(0.05);

            try
                app.T1=PlateFEA.T1;
            catch
            end
            try
                app.T2=PlateFEA.T2;
            catch
            end
            try
                app.Mwd=PlateFEA.Mwd;
            catch
            end
            try
                app.Mww=PlateFEA.Mww;
            catch
            end
            try
                app.Kwd=PlateFEA.Kwd;
            catch
            end
            try
                app.Kww=PlateFEA.Kww;
            catch
            end


            try
                app.stiff_unc=PlateFEA.stiff_unc;
            catch
            end
            try
                app.mass_unc=PlateFEA.mass_unc;
            catch
            end

            try
                app.D=PlateFEA.D;
            catch
            end
            try
                app.nx=PlateFEA.nx;
            catch
            end
            try
                app.number_nodes=PlateFEA.number_nodes;
            catch
            end
            try
                app.dx=PlateFEA.dx;
            catch
            end
            try
                app.dy=PlateFEA.dy;
            catch
            end
            try
                app.node_matrix=PlateFEA.node_matrix;
            catch
            end

            try
                app.icenter=PlateFEA.icenter;
            catch
            end
               
            try
                app.ne=PlateFEA.ne; 
            catch
            end
            try
                app.element_matrix=PlateFEA.element_matrix;
            catch
            end
            try
                app.dof=PlateFEA.dof;
            catch
            end

            
            app.UnitListBox.Value=PlateFEA.unit;
            app.MaterialListBox.Value=PlateFEA.mat;

            try
                app.MassMethodListBox.Value=PlateFEA.massmethod;
            catch
            end

            try
                app.LengthinEditField.Value=PlateFEA.L;
            catch
            end
            try
                app.WidthinEditField.Value=PlateFEA.W;
            catch
            end
            try
                app.ThicknessinEditField.Value=PlateFEA.T;
            catch
            end
   
            try
                app.AlongLengthEditField.Value=PlateFEA.nx;
            catch
            end
            try
                app.AlongWidthEditField.Value=PlateFEA.ny;
            catch
            end
            try
                app.PoissonRatioEditField.Value=PlateFEA.mu;
            catch
            end
            try
                app.ElasticModuluspsiEditField.Value=PlateFEA.E;
            catch
            end
            try
                app.MassDensitylbmin3EditField.Value=PlateFEA.rho;       
            catch
            end
            try
                app.UniformNonstructuralMasslbmEditField.Value=PlateFEA.nsm;
            catch
            end
            try
                app.TotalDistributedMasslbmEditField.Value=PlateFEA.tdm;
            catch
            end
            try
                app.BarePlateMasslbmEditField.Value=PlateFEA.baremass;
            catch
            end


            try
                app.constraint_matrix_top=PlateFEA.constraint_matrix_top;
            catch
            end
            try
                app.constraint_matrix_left=PlateFEA.constraint_matrix_left;               
            catch
            end
            try
                app.constraint_matrix_bottom=PlateFEA.constraint_matrix_bottom;
            catch
            end
            try
                app.constraint_matrix_right=PlateFEA.constraint_matrix_right;              
            catch
            end          
            try
                app.constraint_matrix_added=PlateFEA.constraint_matrix_added;  
            catch
            end
            try
                app.constraint_matrix=PlateFEA.constraint_matrix;                
            catch
            end
            try
                app.ematrix=PlateFEA.ematrix; 
            catch
            end

            progressbar(0.1);

            try
                app.damp_Q=PlateFEA.damp_Q;
            catch
            end
            try
                app.damp_ratio=PlateFEA.damp_ratio;              
            catch
            end
            try
               app.damping_flagPlateFEA.damping_flag;                
            catch
            end

            try
                app.DampingTypeListBox.Value=PlateFEA.nmn;
            catch
            end  
            try
                app.ModalDistributionListBox.Value=PlateFEA.nD;
            catch
            end

            try
                app.ModeShapes=PlateFEA.ModeShapes;
            catch
            end
            try
                app.part_ModeShapes=PlateFEA.part_ModeShapes;
            catch
            end
            
            progressbar(0.2);

            try
                app.ivector=PlateFEA.ivector;
            catch
            end    
            try
                app.emm=PlateFEA.emm;
            catch
            end
            try
                app.ibc=PlateFEA.ibc;
            catch
            end  
            try
                app.ModeShapes_corrected=PlateFEA.ModeShapes_corrected;
            catch
            end

            try
                app.stiff=PlateFEA.stiff;
            catch
            end

            progressbar(0.25);

            try
                app.mass=PlateFEA.mass;
            catch
            end 
            try
                app.stiff_con=PlateFEA.stiff_con;
            catch
            end
            try
                app.mass_con=PlateFEA.mass_con;
            catch
            end    

            try
                app.UITableConstraints.Data=PlateFEA.UITableConstraints;
            catch
            end

            progressbar(0.3);

            try
                app.UniformQEditField.Value=PlateFEA.UQ;
            catch
            end
            try
                app.part_omegan=PlateFEA.part_omegan;
            catch
            end

            try
                app.TopBCListBox.Value=PlateFEA.TBC;
                app.BottomBCListBox.Value=PlateFEA.BBC;        
                app.LeftBCListBox.Value=PlateFEA.LBC;
                app.RightBCListBox.Value=PlateFEA.RBC;
            catch
            end

            progressbar(0.35);
            
            app.volume=0;
            app.area=0;
            app.L=0;
            app.W=0;
            app.T=0;

            app.mesh_flag=0;

            progressbar(0.4);

            GetGeometry(app);
            
            progressbar(0.45);
            
            ChangeDamping(app);
            
            progressbar(0.5);
            
            
            ChangeUnits(app);
            
            progressbar(0.6);
            
            ChangeMassMethod(app);
            
            progressbar(0.65);
            
            ChangePointMass(app);
            
            progressbar(0.7);
            
            update_all_masses(app);

            progressbar(0.75);

            try
                app.NodeNearestCenterEditField.Value=sprintf('%d',app.icenter);
            catch
            end    

            try
                app.ModelNameEditField.Visible='on';
            catch
            end

            try
                app.ModelNameEditFieldLabel.Visible='on';
            catch
            end

            try
                app.nodex=app.node_matrix(:,1);
                app.nodey=app.node_matrix(:,2);
            catch
            end
           

            try
                app.pm_nodes=PlateFEA.pm_nodes;
            catch
            end
            try
                app.pm_num=PlateFEA.pm_num;    
            catch
            end
            try
                app.point_mass_matrix=PlateFEA.point_mass_matrix;
            catch
            end
            try
                app.UITablePM.Data=PlateFEA.UITablePM;       
            catch
            end     

            try
                nq=1+length(PlateFEA.pm_nodes);
                app.PointMassesListBox.Value=app.PointMassesListBox.Items{nq};                  
            catch
            end

            progressbar(0.8);
    

            try % leave as third to last
                app.UITable_damping.Data=PlateFEA.damp_table;

                if(isempty(app.damp_ratio))
                else
                    try
                        SaveTheDamping(app);
                    catch
                    end
                end            
                if(isempty(app.damp_Q))
                else
                    try
                        SaveTheDamping(app);
                    catch
                    end
                end

            catch
            end

           
            try  % leave as last
                app.mesh_flag=PlateFEA.mesh_flag;
            catch
            end

 
            progressbar(0.85);

            try
                ViewConstraints(app);
            catch
            end

            progressbar(0.9);

            try
                PlotMesh(app);
            catch
            end

            progressbar(0.95);
            
            igflag=0;
            try
                app.UITable_fn.Data=PlateFEA.fn_table;
                igflag=1;
                disp('fn table passed')
            catch
                disp('fn table failed')
            end

            if(igflag==1)
                try
                    app.UITable_fn.Visible='on';
                    app.PlotModeShapeButton.Enable='on';
                    disp('enable on')
                catch
                    disp('enable off')
                end    
            end  
            try % leave as second to last
                app.fn=PlateFEA.fn;
            catch
            end   

            progressbar(1);

            msgbox('Load complete');

        end

        % Button pushed function: CalculateKMButton
        function CalculateKMButtonPushed(app, event)
            Clearfn(app);
            
            GetGeometry(app);

            app.E=str2num(app.ElasticModuluspsiEditField.Value);

            if(app.iu==2) % metric
                [app.E]=GPa_to_Pa(app.E);
            end    

            app.mu=str2num(app.PoissonRatioEditField.Value);

            h=app.T;
            try
                app.D=app.E*(h^3)/(12*(1-app.mu^2));
            catch
                app.E
                h
                app.mu
                warndlg('error ref 1')
            end
            app.beta=2*(1-app.mu);

            % disp(' ');
            % disp(' Calculating uncontrained mass and stiffness matrices... ');
            
            node1=app.element_matrix(:,1);
            node2=app.element_matrix(:,2);
            node3=app.element_matrix(:,3);
            node4=app.element_matrix(:,4);

            urho=str2num(app.TotalDistributedMasslbmEditField.Value)/app.volume;

            if(isempty(app.D))
                warndlg('app.D error');
                return;
            end    

            [app.mass,app.stiff,~]=vibrationdata_rectangular_plate_mass_stiff...
              (app.ne,app.nodex,app.nodey,node1,node2,node3,node4,app.mu,app.beta,app.T,app.dof,urho,app.D);   
            %

            pmt=0;
            
            try
                sz=size(app.point_mass_matrix);
            
                if(sz(1)>0 )
               
                    for i=1:sz(1)
                   
                        if(app.point_mass_matrix(i,1)>0)
                       
                            app.pm=app.point_mass_matrix(i,2);
                        
                            n=3*app.point_mass_matrix(i,1)-2;
                        
                            app.mass(n,n)=app.mass(n,n)+app.pm;
                            pmt=pmt+app.pm;
                        
                        end
                    
                    end
                
                end
            catch
            % no point mass
            end

            if(app.iu==1)
                app.mass=app.mass/386;                
            end

            app.mass_unc=app.mass;
            
            if(max(max(app.mass))==0)
                warndlg('mass matrix is zero, ref 1');
                return;
            end
               
            app.stiff_unc=app.stiff;

            msgbox('Unconstrained Matrices Generated');

        end

        % Button down function: UnconstrainedKMTab
        function UnconstrainedKMTabButtonDown(app, event)
            GetGeometry(app);
            
            if(isempty(app.L))
                warndlg('Enter Geometry first, ref 1')
                return;
            end    
            if(isempty(app.W))
                warndlg('Enter Geometry first')
                return;
            end  
            if(isempty(app.T))
                warndlg('Enter Geometry first')
                return;
            end     
            if(app.mesh_flag==0)
                GenerateMeshButtonPushed(app, event);
            end 
        end

        % Button pushed function: ResetConstraintsButton
        function ResetConstraintsButtonPushed(app, event)
            Clearfn(app);
            ConstraintReset(app);

        end

        % Value changed function: TopBCListBox
        function TopBCListBoxValueChanged(app, event)

            Clearfn(app);

            BCListbox(app); 

            app.fixed=[1 1 1]; 
            app.RY_only=[1 0 1];   
            app.RX_only=[1 1 0];

            app.constraint_matrix_top=zeros(1,4);
            
            icn=1;

            if(app.e3==2)     
                for i=(app.nx*(app.ny-1)+1):(app.nx*app.ny) 
                    app.constraint_matrix_top(icn,:)=[i app.RX_only];
                    icn=icn+1;
                end        
            end
            if(app.e3==3)  
                for i=(app.nx*(app.ny-1)+1):(app.nx*app.ny) 
                    app.constraint_matrix_top(icn,:)=[i app.fixed];
                    icn=icn+1;
                end
            end   

            app.constraint_matrix_top

           update_constraints(app);

        end

        % Value changed function: BottomBCListBox
        function BottomBCListBoxValueChanged(app, event)

            Clearfn(app);

            BCListbox(app);

            app.fixed=[1 1 1]; 
            app.RY_only=[1 0 1];   
            app.RX_only=[1 1 0];

            app.constraint_matrix_bottom=zeros(1,4);
            
            icn=1;
            
            if(app.e1==2)
                for i=1:app.nx
                    app.constraint_matrix_bottom(icn,:)=[i app.RX_only];
                    icn=icn+1;
                end        
            end
            if(app.e1==3)
                for i=1:app.nx 
                    app.constraint_matrix_bottom(icn,:)=[i app.fixed];
                    icn=icn+1;
                end
            end

            update_constraints(app);

        end

        % Clicked callback: LeftBCListBox
        function LeftBCListBoxClicked(app, event)
     

        end

        % Clicked callback: RightBCListBox
        function RightBCListBoxClicked(app, event)


        end

        % Value changed function: LeftBCListBox
        function LeftBCListBoxValueChanged(app, event)
            
            Clearfn(app);

            BCListbox(app); 
            
            app.fixed=[1 1 1]; 
            app.RY_only=[1 0 1];   
            app.RX_only=[1 1 0];

            app.constraint_matrix_left=zeros(1,4);
            
            icn=1;
            
            if(app.e4==2)
	            ijk=1;
                for j=1:app.ny
                        app.constraint_matrix_left(icn,:)=[ijk app.RY_only];
                        icn=icn+1;             
                        ijk=ijk+app.nx;
                end          
            end
            if(app.e4==3)
                ijk=1;
                for j=1:app.ny
                    app.constraint_matrix_left(icn,:)=[ijk app.fixed];
                    icn=icn+1;  
                    ijk=ijk+app.nx;
                end     
            end   
            
            update_constraints(app);

        end

        % Button pushed function: UpdateModelViewButton
        function UpdateModelViewButtonPushed(app, event)
            
           ViewConstraints(app);

        end

        % Button pushed function: AddManualButton
        function AddManualButtonPushed(app, event)
            Items = strtrim(app.ConstraintTypeListBox.Items);
            im = strtrim(app.ConstraintTypeListBox.Value);
            k = find(strcmp(Items, im));

            n=str2num(app.ManuallyAddNodeNumberEditField.Value);
            
            sz=size(app.constraint_matrix_added);
       
            m=sz(1);

            try
            
                app.constraint_matrix_added(m+1,1)=n;
            catch
                return;
            end

            if(k==1)
                app.constraint_matrix_added(m+1,2:4)=[1 1 1];
            end
            if(k==2)
                app.constraint_matrix_added(m+1,2:4)=[1 0 0];
            end            
            
            update_constraints(app);

        end

        % Value changed function: RightBCListBox
        function RightBCListBoxValueChanged(app, event)
            Clearfn(app);
            
            BCListbox(app); 

            app.fixed=[1 1 1]; 
            app.RY_only=[1 0 1];   
            app.RX_only=[1 1 0];

            app.constraint_matrix_right=zeros(1,4);
            
            icn=1;
    
            if(app.e2==2)
                ijk=app.nx;
                for j=1:app.ny
                    app.constraint_matrix_right(icn,:)=[ijk app.RY_only];
                    icn=icn+1;           
                    ijk=ijk+app.nx;
                end        
            end
            if(app.e2==3)
                 ijk=app.nx;
                 for j=1:app.ny
                     app.constraint_matrix_right(icn,:)=[ijk app.fixed];
                     icn=icn+1;           
                     ijk=ijk+app.nx;
                 end          
            end

            update_constraints(app);
        end

        % Value changed function: DampingTypeListBox
        function DampingTypeListBoxValueChanged(app, event)
            ChangeDamping(app);
        end

        % Value changed function: ModalDistributionListBox
        function ModalDistributionListBoxValueChanged(app, event)

            ChangeDamping(app);
        end

        % Button pushed function: SaveDampingButton
        function SaveDampingButtonPushed(app, event)
  
            SaveTheDamping(app);
                     
            app.damping_flag=1;
   
            msgbox('Damping values saved'); 


        end

        % Button pushed function: PartitionButton
        function PartitionButtonPushed(app, event)
            %  ea=[1 2]  dof with enforced
            
            % num total dof
            % nem number of dof with enforced acceleration
            
            %
            %  etype =1  enforced acceleration
            %         2  enforced displacement
            %
            
            
            pmass=app.mass_unc;
            
            if(max(max(pmass))==0)
                warndlg(' mass matrix is zero, ref 7 ');
                return;
            end
            
            pstiff=app.stiff_unc;
            
            sz=size(pmass);
            app.dof=sz(1);
            
            app.tracking_array=zeros(app.dof,1);
            
            
            for i=1:app.dof
                app.tracking_array(i)=i;
                app.free_dof_array(i)=i;
            end
            
            app.TZ_tracking_array=zeros(app.dof,2);
            
            num_nodes=round(app.dof/3);
            
            for i=1:num_nodes
               app.TZ_tracking_array(i)=3*i-2;
            end
            
            
            k=1;
            
            pibc=1;
            
            try

                n=length(app.constraint_matrix(:,1));   
                
                
                for i=1:n
                    if(app.constraint_matrix(i,2)==1)
            
                        ea(k)=3*app.constraint_matrix(i,1)-2;
                        
            %%           out1=sprintf('* %d %d %8.4g  ',i,n,ea(k));
            %%           disp(out1);
                        
                        k=k+1;
                        
                    end
                    
                    if(app.constraint_matrix(i,3)==1 || app.constraint_matrix(i,4)==1  )
                        rot_constraint_matrix(pibc,1)=app.constraint_matrix(i,1);
                        rot_constraint_matrix(pibc,2)=0;
                        rot_constraint_matrix(pibc,3)=app.constraint_matrix(i,3);
                        rot_constraint_matrix(pibc,4)=app.constraint_matrix(i,4);
                        pibc=pibc+1;
                    end
                    
                    
                end
                
            catch
                warndlg('constraint matrix unavailable');
                return;
            end
            
            pibc=pibc-1;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sz=size(app.constraint_matrix);
            
            nc=sz(1);
            
            k=1;
            for i=1:nc 
                
                 cmn=app.constraint_matrix(i,1);
                
                 if(app.constraint_matrix(i,2)==1)
                    cdof(k)=3*cmn-2;
                    k=k+1;
                 end    
                 if(app.constraint_matrix(i,3)==1)
                    cdof(k)=3*cmn-1;
                    k=k+1;
                 end
                 if(app.constraint_matrix(i,4)==1)
                    cdof(k)=3*cmn;
                    k=k+1;
                 end     
            end
            
            cdof=sort(cdof,'descend');
            
            for i=1:length(cdof)
                app.free_dof_array(cdof(i))=[];
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              
            k=1;
            for i=1:pibc 
                 if(rot_constraint_matrix(i,3)==1)
                    rot_dof(k)=3*rot_constraint_matrix(i,1)-1;
                    k=k+1;
                 end
                 if(rot_constraint_matrix(i,4)==1)
                    rot_dof(k)=3*rot_constraint_matrix(i,1);
                    k=k+1;
                 end     
            end
            
            rot_dof=sort(rot_dof);
            pibc=length(rot_dof);    
                   
            ea=unique(ea);
            app.nem=length(ea);

            ea=fix_size(ea);
            
            eac(:,1)=ea;
            
            
            if(pibc>=1)  % correct ea matrix
                
                rot_dof=sort(rot_dof,'descend');
                
                for i=1:pibc
                    for j=1:app.nem
                        if(ea(j)>rot_dof(i))
                            ea(j)=ea(j)-1;
            %%                out7=sprintf(' %d %d ',ea(j),rot_dof(i));
            %%                disp(out7);
                        end
                    end
                end
                
                drot_dof=sort(rot_dof,'descend');
                
                for i=1:pibc
                    
                  app.tracking_array(drot_dof(i))=[];
                     
                end   
            
                
                for i=1:pibc
                    for j=1:num_nodes
                        if( app.TZ_tracking_array(j)>rot_dof(i) )
                               app.TZ_tracking_array(j)=app.TZ_tracking_array(j)-1;
                        end       
                    end
                end    
                
                
            end
            
            
            eac(:,2)=ea;
            
            for i=1:length(ea)
                eac(i,3)=eac(i,2)-eac(i,1);
            end
                
            
            ea=unique(ea);
            app.nem=length(ea);
            
            
            if(app.nem==0)
                warndlg('No drive dofs');
                return;    
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            if(pibc>1)  % apply rotational constraints
                [pmass,pstiff,~,~]=...
                    rectangular_plate_apply_constraints(rot_constraint_matrix,pibc,pmass,pstiff);
            end

            sz=size(pmass);
            num=sz(1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
             
            etype=1;
             
            % num total dof
            % nem number of dof with enforced acceleration
             
            % disp(' Track Changes ');  
             
            [app.ngw]=track_changes(app.nem,num,ea);
             
            dtype=2; % display partitioned matrices
            
            %% out1=sprintf('\n ** num=%d  nem=%d \n',num,nem);
            %% disp(out1);
            
             
            % disp(' Enforced Partition Matrices ');
            
            % disp(' mass ');
            
            % pmass(1:10,1:10)
            
            [app.TT,app.T1,app.T2,app.Mwd,app.Mww,app.Kwd,app.Kww]=...
                            enforced_partition_matrices(num,ea,pmass,pstiff,etype,dtype);
            
            
            % disp(' Generalized Eigen ');
                        
            [app.part_fn,app.part_omegan,app.part_ModeShapes,app.part_MST]=Generalized_Eigen(app.Kww,app.Mww,2);            

            msgbox('Partitioning Complete');    
        end

        % Size changed function: NaturalFreqTab
        function NaturalFreqTabSizeChanged(app, event)
       
            
        end

        % Value changing function: ElasticModuluspsiEditField
        function ElasticModuluspsiEditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanging(app, event)
          
            Clearfn(app);
        end

        % Value changing function: MassDensitylbmin3EditField
        function MassDensitylbmin3EditFieldValueChanging(app, event)
            Clearfn(app);
        end

        % Value changing function: UniformNonstructuralMasslbmEditField
        function UniformNonstructuralMasslbmEditFieldValueChanging(app, event)
            
            Clearfn(app);
        end

        % Button pushed function: PlotModeShapeButton
        function PlotModeShapeButtonPushed(app, event)
  
            app.fig_num=10;

            k=str2num(app.ModeListBox.Value);

            app.nodex=app.node_matrix(:,1);
            app.nodey=app.node_matrix(:,2);
            
            [app.fig_num]=...
            vibrationdata_rectangular_elements_plot_modes(app.nodex,...
                            app.nodey,app.fig_num,app.ModeShapes_corrected,app.fn,...
                                  app.number_nodes,k,app.iu,app.nx,app.ny);
    
        end

        % Button down function: DampingTab
        function DampingTabButtonDown(app, event)
            if(app.T==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end    
            if(app.L==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end  
            if(app.W==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end     
            if(app.mesh_flag==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end
            if(isempty(app.mass_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.stiff_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end 
            if(isempty(app.damp_Q))
            else
                SaveTheDamping(app);
            end
        end

        % Button down function: PartitionMatricesTab
        function PartitionMatricesTabButtonDown(app, event)
            try
                SaveTheDamping(app);
            catch
            end
            if(app.T==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end    
            if(app.L==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end  
            if(app.W==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end     
            if(app.mesh_flag==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end
            if(isempty(app.mass_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.stiff_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end    
            if(isempty(app.damp_Q))
                warndlg('Save Damping first');
                return;
            end           
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end

            app.ResponseNodeEditField.Value=app.NodeNearestCenterEditField.Value;   
        end

        % Size changed function: DampingTab
        function DampingTabSizeChanged(app, event)
           
            
        end

        % Callback function
        function NearesttoCenterButtonPushed(app, event)
            
        end

        % Callback function
        function MaxEigenvectorCoefficientMode1ButtonPushed(app, event)
           
            B=app.ModeShapes_corrected(1:3:end,1);
            
            [~,I]=max(abs(B));
              
            app.ResponseNodeEditField.Value=sprintf('%d',I);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            reset(app);
        end

        % Callback function
        function MaxEigenvectorCoefficientMode2ButtonPushed(app, event)
            B=app.ModeShapes_corrected(1:3:end,2);
            
            [~,I]=max(abs(B));
              
            app.ResponseNodeEditField.Value=sprintf('%d',I);            
        end

        % Button pushed function: CalculateTransmissibilityButton
        function CalculateTransmissibilityButtonPushed(app, event)

            app.fig_num=40;

            fmin=str2num(app.MinEditField_trans.Value);
            fmax=str2num(app.MaxEditField_trans.Value);

            if(isempty(app.damp_ratio))
                warndlg('Save Damping first')
                return
            end               

            Items = strtrim(app.OutputFrequencySpacingListBox.Items);
            im = strtrim(app.OutputFrequencySpacingListBox.Value);
            ndf = find(strcmp(Items, im));

            oct=2^(1/96);
            
            app.f=zeros(10000,1);

            app.f(1)=fmin;
            
            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3)     
                    app.f(k)=app.f(k-1)*oct;
                end
                
                if(app.f(k)>fmax)
                    app.f(k)=fmax;
                    break;
                end
                
                k=k+1;
            end
            
            app.f=app.f(1:k);

            TransMain(app);
          
            ah=[app.f app.acc];
            
            ppp=ah;
            
            t_string=sprintf('Acceleration Transmissibility at node %d',app.node);
            
            x_label='Frequency (Hz)';
            y_label='Trans (G/G)';
            
            md=6;
            
            [app.fig_num]=plot_loglog_function_md(app.fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            
            
            if(app.iu==1)
                y_label='Trans (in/G)';
                app.scale=386;
            else
                y_label='Trans (mm/G)';
                app.scale=9.81*1000;    
            end  
            
            
            rh=[app.f app.scale*app.rd];
            ppp=rh;
                
            t_string=sprintf('Relative Displacement Trans at node %d',app.node);
            
            x_label='Frequency (Hz)';
            
            md=6;
            
            if(max(app.rd)>1.0e-08 && min(app.rd)>1.0e-80)
            
                [app.fig_num]=plot_loglog_function_md(app.fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);
            else
                msgbox(' Relative displacement is zero at this node .'); 
            end    
                                
            %%
            
            maxHA=0.;
            maxHrd=0.;
            
            for k=1:length(app.f)
                      
                    if(app.acc(k)>maxHA)
                        maxHA=app.acc(k);
                        maxFA=app.f(k);
                    end
               
                    if(rh(k,2)>maxHrd)
                        maxHrd=rh(k,2);
                        maxFrd=app.f(k);
                    end        
              
            end
                        
            out1=sprintf('\n  max Accel Trans    = %8.4g (G/G) at %8.4g Hz ',maxHA,maxFA);
            disp(out1);
            
            if(max(app.rd)>1.0e-08 && min(app.rd)>1.0e-80)
                if(app.iu==1)
                    out1=sprintf('\n  max Rel Disp Trans = %8.4g (in/G) at %8.4g Hz ',maxHrd,maxFrd);
                else
                    out1=sprintf('\n  max Rel Disp Trans = %8.4g (mm/G) at %8.4g Hz ',maxHrd,maxFrd);    
                end
                disp(out1);
            end


            output_name1=sprintf('accel_trans_node_%d',app.node);
            output_name2=sprintf('rel_disp_trans_node_%d',app.node);

            ss=sprintf('%s\n%s',output_name1,output_name2);
            app.TransOutputArraysReadOnlyTextArea.Value=ss;
            
            assignin('base', output_name1, ah);
            assignin('base', output_name2, rh);

        end

        % Button down function: TransmissibilityTab
        function TransmissibilityTabButtonDown(app, event)
            app.TransOutputArraysReadOnlyTextArea.Value='';
            
            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(isempty(app.TT))
                warndlg('Partition first');
                return;
            end    
            if(isempty(app.nem))
                warndlg('Partition first');
                return;
            end       
            if(isempty(app.TZ_tracking_array))
                warndlg('Partition first');
                return;            
            end  
            if(isempty(app.Mwd))
                warndlg('Partition first');
                return;            
            end   
            if(isempty(app.part_ModeShapes))
                warndlg('Partition first');
                return;            
            end  

            SetResponseNode(app);
        end

        % Button down function: SineTab
        function SineTabButtonDown(app, event)
            ClearSine(app);

            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(isempty(app.TT))
                warndlg('Partition first');
                return;
            end    
            if(isempty(app.nem))
                warndlg('Partition first');
                return;
            end       
            if(isempty(app.TZ_tracking_array))
                warndlg('Partition first');
                return;            
            end  
            if(isempty(app.Mwd))
                warndlg('Partition first');
                return;            
            end   
            if(isempty(app.part_ModeShapes))
                warndlg('Partition first');
                return;            
            end  

            SetResponseNode(app);
        end

        % Button down function: PSDTab
        function PSDTabButtonDown(app, event)
            app.PSDOutputArraysReadOnlyTextArea.Value='';
            
            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(isempty(app.TT))
                warndlg('Partition first');
                return;
            end    
            if(isempty(app.nem))
                warndlg('Partition first');
                return;
            end       
            if(isempty(app.TZ_tracking_array))
                warndlg('Partition first');
                return;            
            end  
            if(isempty(app.Mwd))
                warndlg('Partition first');
                return;            
            end   
            if(isempty(app.part_ModeShapes))
                warndlg('Partition first');
                return;            
            end  

            SetResponseNode(app);
        end

        % Button down function: ArbitraryTimeHistoryTab
        function ArbitraryTimeHistoryTabButtonDown(app, event)
            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(isempty(app.TT))
                warndlg('Partition first');
                return;
            end    
            if(isempty(app.nem))
                warndlg('Partition first');
                return;
            end       
            if(isempty(app.TZ_tracking_array))
                warndlg('Partition first');
                return;            
            end  
            if(isempty(app.Mwd))
                warndlg('Partition first');
                return;            
            end   
            if(isempty(app.part_ModeShapes))
                warndlg('Partition first');
                return;            
            end  

            SetResponseNode(app);
        end

        % Button down function: MDOFSRSTab
        function MDOFSRSTabButtonDown(app, event)
            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(isempty(app.TT))
                warndlg('Partition first');
                return;
            end    
            if(isempty(app.nem))
                warndlg('Partition first');
                return;
            end       
            if(isempty(app.TZ_tracking_array))
                warndlg('Partition first');
                return;            
            end  
            if(isempty(app.Mwd))
                warndlg('Partition first');
                return;            
            end   
            if(isempty(app.part_ModeShapes))
                warndlg('Partition first');
                return;            
            end  

            SetResponseNode(app);
        end

        % Value changed function: SetNodetoListBox
        function SetNodetoListBoxValueChanged(app, event)
            ClearSine(app);
            SetResponseNode(app);
            app.TransOutputArraysReadOnlyTextArea.Value='';
            app.PSDOutputArraysReadOnlyTextArea.Value='';
        end

        % Size changed function: BaseExcitationTab
        function BaseExcitationTabSizeChanged(app, event)
            position = app.BaseExcitationTab.Position;
            
        end

        % Button down function: BaseExcitationTab
        function BaseExcitationTabButtonDown(app, event)
            SetResponseNode(app);
        end

        % Button pushed function: CalculateSineResponseButton
        function CalculateSineResponseButtonPushed(app, event)
            
            app.f=str2num(app.FreqHzEditField.Value);
            amp=str2num(app.AccelGEditField.Value);

            TransMain(app);

            Ar=app.acc*amp;
            Dr=app.rd*amp;

            if(app.iu==1)
                Dr=Dr*386;
                ss=sprintf('Accel = %7.4g G \n\n Rel Disp = %7.4g in \n',Ar,Dr);
            else
                Dr=Dr*9.81*1000;  
                ss=sprintf('Accel = %7.4g G \n\n Rel Disp = %7.4g mm \n',Ar,Dr);                
            end    


            app.SineResponseTextArea.Value=ss;
            app.SineResponseTextArea.Visible='on';
            app.SineResponseTextAreaLabel.Visible='on';
        end

        % Value changing function: FreqHzEditField
        function FreqHzEditFieldValueChanging(app, event)
         
            ClearSine(app);
        end

        % Value changing function: AccelGEditField
        function AccelGEditFieldValueChanging(app, event)
            
            ClearSine(app);
        end

        % Value changing function: ResponseNodeEditField
        function ResponseNodeEditFieldValueChanging(app, event)
            
            ClearSine(app);

            app.ResultsTextArea_srs.Value='';
            app.ResponseResultsReadOnlyTextArea.Value='';
            app.PSDOutputArraysReadOnlyTextArea.Value='';
        end

        % Value changed function: NumberofModestoIncludeListBox
        function NumberofModestoIncludeListBoxValueChanged(app, event)
            
            ClearSine(app);

            app.ResultsTextArea_srs.Value='';
            app.ResponseResultsReadOnlyTextArea.Value='';
            app.PSDOutputArraysReadOnlyTextArea.Value='';
        end

        % Button pushed function: SettoFundamentalFrequencyButton
        function SettoFundamentalFrequencyButtonPushed(app, event)
            app.FreqHzEditField.Value=sprintf('%7.4g',app.fn(1));
        end

        % Button pushed function: UpdateListButton_psd
        function UpdateListButton_psdPushed(app, event)
            update_psd(app);
        end

        % Value changed function: listbox_method_psd
        function listbox_method_psdValueChanged(app, event)
            
            Items = char(app.listbox_method_psd.Items);
            im=app.listbox_method_psd.Value;
            [app.method]=find_index(Items,im);
           
            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_psd(app);
               app.SelectInputArrayListBox_psd.Value=name;
            end    
        end

        % Button pushed function: CalculatePSDResponseButton
        function CalculatePSDResponseButtonPushed(app, event)
            try
                Items = char(app.listbox_method_psd.Items);
                im=app.listbox_method_psd.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_psd.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            fmin=app.THM(1,1);
            fmax=app.THM(end,1);


            Items = strtrim(app.OutputFrequencySpacingListBox_psd.Items);
            im = strtrim(app.OutputFrequencySpacingListBox_psd.Value);
            ndf = find(strcmp(Items, im));

            if(ndf==3)
                oct=2^(1/96);
            end
            if(ndf==4)
                oct=2^(1/48);
            end    
            
            app.f=zeros(10000,1);

            app.f(1)=fmin;
            
            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3 || ndf==4)     
                    app.f(k)=app.f(k-1)*oct;
                end
                
                if(app.f(k)>fmax)
                    app.f(k)=fmax;
                    break;
                end
                
                k=k+1;
            end
            
            app.f=app.f(1:k);
  
            TransMain(app);

            ah=[app.f app.acc.*app.acc];

            if(app.iu==1)
                app.scale=386;
            else
                app.scale=9.81*1000;    
            end  

            app.rd=app.scale*app.rd;
             
            rh=[app.f app.rd.*app.rd];

            [base_psd]=interp_psd_oct(app.THM(:,1),app.THM(:,2),app.f);

            accel_psd = base_psd .*ah(:,2); 
            rd_psd = base_psd .*rh(:,2);


            [~,base_grms]=calculate_PSD_slopes_no(app.THM(:,1),app.THM(:,2));
            [~,response_grms]=calculate_PSD_slopes(app.f,accel_psd);
            [~,rd_rms]=calculate_PSD_slopes(app.f,rd_psd);            

            t_string=sprintf('Accel PSD  at Node %d',app.node);
            y_label='Accel (G^2/Hz)';
            x_label='Frequency (Hz)';

            app.f=fix_size(app.f);
            accel_psd=fix_size(accel_psd);

            ppp1=[app.f accel_psd];
            ppp2=app.THM;

            size(ppp1)
            size(ppp2);

            md=5;

            app.fig_num=80;

            leg1=sprintf('Response  %7.3g GRMS',response_grms);
            leg2=sprintf('Base Input  %7.3g GRMS',base_grms);

            [app.fig_num,h2]=plot_loglog_function_md_two_h2(app.fig_num,x_label,...
                    y_label,t_string,ppp1,ppp2,leg1,leg2,fmin,fmax,md);

            rd_psd=fix_size(rd_psd);

            ppp=[app.f rd_psd];

                if(app.iu==1)
                    t_string=sprintf('Relative Displacement PSD at Node %d   %7.3g in RMS',app.node,rd_rms);
                    y_label='Rel Disp (in^2/Hz)';
                else
                    t_string=sprintf('Relative Displacement PSD at Node %d   %7.3g mm RMS',app.node,rd_rms);
                    y_label='Rel Disp (mm^2/Hz)';
                end    

                [app.fig_num,~]=...
                    plot_loglog_function_md_h2(app.fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md); 

                %%            

            output_name1=sprintf('accel_psd_node_%d',app.node);
            output_name2=sprintf('rel_disp_psd_node_%d',app.node);

            ss=sprintf('%s\n%s',output_name1,output_name2);
            app.PSDOutputArraysReadOnlyTextArea.Value=ss;
            
            assignin('base', output_name1, [app.f accel_psd]);
            assignin('base', output_name2, [app.f rd_psd]);


        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update_th(app);
        end

        % Button pushed function: CalculateBaseInputArbitrary
        function CalculateBaseInputArbitraryButtonPushed(app, event)
            
            CloseFigures(app);

            try
                Items = char(app.listbox_method.Items);
                im=app.listbox_method.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end 

            disp(' ');
            disp(' * * * * * ');
            disp(' ');

            FEA_base_arbitrary(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_th(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Size changed function: ArbitraryTimeHistoryTab
        function ArbitraryTimeHistoryTabSizeChanged(app, event)
            position = app.ArbitraryTimeHistoryTab.Position;
            
        end

        % Button pushed function: UpdateListButton_srs
        function UpdateListButton_srsPushed(app, event)
            update_srs(app);
        end

        % Value changed function: listbox_method_srs
        function listbox_method_srsValueChanged(app, event)
            Items = char(app.listbox_method_srs.Items);
            im=app.listbox_method_srs.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_srs(app);
               try
                    app.SelectInputArrayListBox_srs.Value=name;
               catch
               end
            end    
        end

        % Button pushed function: CalculateBaseInputMDOFSRS
        function CalculateBaseInputMDOFSRSButtonPushed(app, event)
            CloseFigures(app);

            app.fig_num=60;

            try
                Items = char(app.listbox_method_srs.Items);
                im=app.listbox_method_srs.Value;
                [n]=find_index(Items,im);
            catch
                warndlg(' SelectInputArrayListBox  failed');
                return;
            end
         
            if(n==1)
                 try
                     FS=app.SelectInputArrayListBox_srs.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end  

            app.node=str2num(app.ResponseNodeEditField.Value);

            num_modes=str2num(app.NumberofModestoIncludeListBox.Value);

            Q=app.SpecificationQEditField.Value;
    
            t_string=sprintf('SRS Q=%g',Q);
           
            md=6;
            x_label='Natural Frequency (Hz)';
            y_label='Peak Accel (G)';
            ppp=app.THM;
            fmin=app.THM(1,1);
            fmax=app.THM(end,1);
                             
            [app.fig_num]=plot_loglog_function_md(app.fig_num,x_label,y_label,t_string,ppp,fmin,fmax,md);                     

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fnn=app.fn;

            if(app.fn(1)<app.THM(1,1))
               warndlg(' First Natural Frequency < First Specification Frequency '); 
               return 
            end  
            while(fnn(end)>app.THM(end,1))
                fnn(end)=[];
            end 

            num_fn=min([num_modes length(fnn)]);

            app.fn(1:12)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                          
            ff=app.THM(:,1);
            a=app.THM(:,2);

            num_srs=length(ff);
            
            slope=zeros(num_srs-1,1);
            
            for i=1:(num_srs-1)
                slope(i)=log(a(i+1)/a(i))/log(ff(i+1)/ff(i));
            end
            
            a_srs=zeros(num_fn,1);
            pv_srs=zeros(num_fn,1);
            rd_srs=zeros(num_fn,1);

            om=2*pi*app.fn;
            
            for i=1:num_fn
              
                for k=1:(num_srs-1)
                    if(app.fn(i)>=ff(k) && app.fn(i)<=ff(k+1))
                         a_srs(i)=a(k)*(app.fn(i)/ff(k))^slope(k);
                        pv_srs(i)=a_srs(i)/om(i); 
                        rd_srs(i)=a_srs(i)/om(i)^2;    
                        break;
                    end    
                end
            end 

            % app.xx=[0. 0.5 1]*app.L;
            nxx=1;

            rdof=3*app.node-2;
           
            ev(1,:)=app.ModeShapes_corrected(rdof,:);  
            
            accel_abssum=zeros(nxx,1);
              accel_srss=zeros(nxx,1);
              
            pv_abssum=zeros(nxx,1);
              pv_srss=zeros(nxx,1);  
            
            rd_abssum=zeros(nxx,1);
              rd_srss=zeros(nxx,1);
            
            for i=1:nxx

                for j=1:num_fn
                    
                    PFM=app.pff(j)*ev(i,j);
                    % fprintf(' i=%d  j=%d fn(j)=%6.3g PFM=%7.3g  a_srs(%d)=%7.3g \n',i,j,app.fn(j),PFM,j,a_srs(j));
                    
                    accel_abssum(i)=accel_abssum(i)+abs(PFM*a_srs(j));
                      accel_srss(i)=accel_srss(i)+(PFM*a_srs(j))^2;
              
                       pv_abssum(i)=pv_abssum(i)+abs(PFM*pv_srs(j));
                         pv_srss(i)=pv_srss(i)+(PFM*pv_srs(j))^2;             
                      
                       rd_abssum(i)=rd_abssum(i)+abs(PFM*rd_srs(j));
                         rd_srss(i)=rd_srss(i)+(PFM*rd_srs(j))^2;   
                end
                
                accel_srss(i)=sqrt( accel_srss(i) );
                   pv_srss(i)=sqrt(    pv_srss(i) );
                   rd_srss(i)=sqrt(    rd_srss(i) );
            end
            
            if(app.iu==1)
                dconv=386;
                vconv=386;
            else
                dconv=9.81*1000;
                vconv=9.81*100;
            end
            
            pv_abssum=pv_abssum*vconv;
            pv_srss=pv_srss*vconv;   
            
            rd_abssum=rd_abssum*dconv;
            rd_srss=rd_srss*dconv;   
                
            %  Acceleration is actually relative accel 
            
            ss1=sprintf('Acceleration (G)');
            ss2=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss1,ss2);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',accel_srss(i),accel_abssum(i));
                ss=sprintf('%s %s',ss,ss3);
            end

            ss3=sprintf('Pseudo Velocity (in/sec)');
            ss=sprintf('%s \n %s',ss,ss3);
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.4g \t %8.4g \n',pv_srss(i),pv_abssum(i));
                ss=sprintf('%s %s',ss,ss3);       
            end
           
            ss3=sprintf('Relative Displacement (in)');
            ss=sprintf('%s \n %s ',ss,ss3);    
            ss3=sprintf('SRSS \t Abssum');
            ss=sprintf('%s \n %s \n',ss,ss3);
            
            for i=1:nxx
                ss3=sprintf('%8.3g \t %8.3g \n',rd_srss(i),rd_abssum(i));
                ss=sprintf('%s %s',ss,ss3);        
            end

            app.ResultsTextArea_srs.Value=ss;
        end

        % Button down function: AppliedPressureTab
        function AppliedPressureTabButtonDown(app, event)
           SetResponseNode(app);
        end

        % Button down function: 
        % UniformFullyCorrelatedPressureTransferFunctionTab
        function UniformFullyCorrelatedPressureTransferFunctionTabButtonDown(app, event)
            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end    
            if(app.T==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end    
            if(app.L==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end  
            if(app.W==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end     
            if(app.mesh_flag==0)
                warndlg('Enter Geometry & Generate Mesh first')
                return;
            end
            if(isempty(app.mass_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.stiff_unc))
                warndlg('Generate Unconstrained K & M first')
                return;
            end    
            if(isempty(app.fn))
                warndlg('Calculate Natural Frequencies first');
                return;
            end    
            if(isempty(app.damp_Q))
                warndlg('Save Damping first');
                return;
            end           
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end

            SetResponseNode(app);
        end

        % Value changed function: SetNodetoListBox_pressure
        function SetNodetoListBox_pressureValueChanged(app, event)
            SetResponseNode(app);
            
        end

        % Button pushed function: CalculateTransmissibilityButton_pressure
        function CalculateTransmissibilityButton_pressurePushed(app, event)
            
            app.node=str2num(app.ResponseNodeEditField_pressure.Value);

            fmin=str2num(app.MinEditField_trans_pressure.Value);
            fmax=str2num(app.MaxEditField_trans_pressure.Value);

            Items = strtrim(app.OutputFrequencySpacingListBox_pressure.Items);
            im = strtrim(app.OutputFrequencySpacingListBox_pressure.Value);
            ndf = find(strcmp(Items, im));

            oct=2^(1/96);
            
            app.f=zeros(10000,1);

            app.f(1)=fmin;
            
            k=2;
            while(1)
            
                if(ndf==1)
                    app.f(k)=app.f(k-1)+1;
                end    
                if(ndf==2)
                    app.f(k)=app.f(k-1)+0.5;
                end
                if(ndf==3)     
                    app.f(k)=app.f(k-1)*oct;
                end
                
                if(app.f(k)>fmax)
                    app.f(k)=fmax;
                    break;
                end
                
                k=k+1;
            end
            
            app.f=app.f(1:k);

            app.fig_num=20;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            num_modes=str2num(app.NumberofModestoIncludeListBox_pressure.Value);
            
            num_modes=min([num_modes length(app.fn) ]);

            QE=app.ModeShapes_corrected;
           
            szn=size(app.node_matrix);
            NL=szn(1);
            
            pressure=1;
            
            total_force=pressure*app.area;
            
            nodal_force=total_force/NL;
      
            %%%%%%%%%

            szn=size(app.node_matrix);
            NL=szn(1);
            
            sz=size(app.constraint_matrix);
            CL=sz(1);
                        
            dof_matrix=zeros(NL,3);
            
            
            for i=1:CL
                j=app.constraint_matrix(i,1);
                dof_matrix(j,:)=[ app.constraint_matrix(i,2) app.constraint_matrix(i,3) app.constraint_matrix(i,4)  ];
            end
            
            
            % dof matrix  row index is node.   Three columns for TZ, RX, RY.  1=constrained
            % dof_matrix
            
            
            nzeros=nnz(~dof_matrix);
            
            cr_matrix=zeros(nzeros,2);
            
            
            k=1;
            
            for i=1:NL
               
                if(dof_matrix(i,1)==0)
                    cr_matrix(k,:)=[i 1];
                    k=k+1;
                end
                if(dof_matrix(i,2)==0)
                    cr_matrix(k,:)=[i 2];
                    k=k+1;
                end    
                if(dof_matrix(i,3)==0)
                    cr_matrix(k,:)=[i 3];
                    k=k+1;
                end    
            
                
            end    

            for i=1:nzeros
                if(app.node==cr_matrix(i,1) && cr_matrix(i,2)==1)
                    response_dof=i;
                end
            end
            
            if(response_dof==0)
               warndlg('Response node not found');
               return;
            end
            
            % cr matrix format:  node & free dof
            % cr_matrix
            
            % out1=sprintf('Response Node at dof = %d ',response_dof);
            % disp(out1);
            
            k=1;
            
            for i=1:nzeros
                if(cr_matrix(i,2)==1)
                    zz(k,1)=i;
                    zz(k,2)=cr_matrix(i,1);
                    k=k+1;
                end
            end
            
            % zz format;  z-dof  node
            % zz
            % zzn=length(zz(:,1));

            %%%%%%%%%

            omega=2*pi*app.f;
            
            omegan=2*pi*app.fn;

            if(isempty(app.damp_ratio))
                SaveTheDamping(app);
            end
            if(isempty(app.damp_ratio))
                warndlg('Save Damping first');
                return;
            end
            if(isempty(app.f))
                warndlg('f empty');
                return
            end    
            if(isempty(QE))
                warndlg('QE empty');
                return
            end  
            if(isempty(omegan))
                warndlg('omegan empty');
                return
            end  
            if(isempty(omega))
                warndlg('omega empty');
                return
            end  
            if(isempty(zz))
                warndlg('zz empty');
                return
            end  


            [Ha,Hv,Hd]=plate_transfer_fea(app.f,app.fn,QE,response_dof,num_modes,app.damp_ratio,omegan,omega,zz);  
            
            try
                Ha=Ha*nodal_force;
                Hv=Hv*nodal_force;
                Hd=Hd*nodal_force;
            catch
                out1=sprintf('H error');
                warndlg(out1);
                return; 
            end
            
            %%%%%%%%%
            
            if(app.iu==1)
                Ha=Ha/386;
                ya_label='Accel/Pressure (G/psi)';
                yv_label='Vel/Pressure (in/sec/psi)';
                yd_label='Disp/Pressure (in/psi)';
                ssa='(G/psi)';
                ssv='(in/sec/psi)';
                ssd='(in/psi)';
            else 
                Ha=Ha/9.81;
                ya_label='Accel/Pressure (G/Pa)';
                yv_label='Vel/Pressure (m/sec/Pa)';
                yd_label='Disp/Pressure (mm/Pa)';    
                ssa='(G/Pa)';
                ssv='(m/sec/Pa)';
                ssd='(mm/Pa)';    
            end
            
            
            %%%%%%%%%
            
            md=4;
            freq=app.f;
            
            
            t_string='Acceleration Transfer Function';
            
            [app.fig_num]=plot_frf_md(app.fig_num,freq,Ha,fmin,fmax,t_string,ya_label,md);
            
            %%%%%%%%%
            
            t_string='Velocity Transfer Function';
            
            [app.fig_num]=plot_frf_md(app.fig_num,freq,Hv,fmin,fmax,t_string,yv_label,md);
            
            %%%%%%%%%

            if(app.iu==2)
                Hd=Hd*1000;
            end    
            
            t_string='Displacement Transfer Function';
            
            [app.fig_num]=plot_frf_md(app.fig_num,freq,Hd,fmin,fmax,t_string,yd_label,md);
            
            %%%%%%%%%
            
            freq=fix_size(freq);
            
            [axmax,aymax]=find_max([freq abs(Ha)]);
            [vxmax,vymax]=find_max([freq abs(Hv)]);
            [dxmax,dymax]=find_max([freq abs(Hd)]);


            disp(' ');
            disp(' Maximum Values');
            disp(' ');
            
            out1=sprintf('Acceleration/Pressure:  %7.4g Hz, %7.4g %s',aymax,axmax,ssa);
            out2=sprintf('    Velocity/Pressure:  %7.4g Hz, %7.4g %s',vymax,vxmax,ssv);
            out3=sprintf('Displacement/Pressure:  %7.4g Hz, %7.4g %s',dymax,dxmax,ssd);
            
            disp(out1);
            disp(out2);
            disp(out3);
            disp(' ');
            
            %%%%%%%%%

            output_name1=sprintf('accel_pressure_trans_%d',app.node);    
            output_name2=sprintf('vel_pressure_trans_%d',app.node);
            output_name3=sprintf('disp_pressure_trans_%d',app.node);

            assignin('base', output_name1, [freq abs(Ha)]);
            assignin('base', output_name2, [freq abs(Hv)]);
            assignin('base', output_name3, [freq abs(Hd)]);            
            
            ss=sprintf(' %s \n %s \n %s',output_name1,output_name2,output_name3);

            app.TransOutputArraysReadOnlyTextArea_pressure.Value=ss;
         
        end

        % Button pushed function: ViewConstraintsButton
        function ViewConstraintsButtonPushed(app, event)
            try
                ViewConstraints(app);
            catch
            end
        end

        % Button pushed function: AddFourcornersTZButton
        function AddFourcornersTZButtonPushed(app, event)


            app.constraint_matrix_added(end+1,:)=[1 1 0 0];
            app.constraint_matrix_added(end+1,:)=[app.nx 1 0 0];
            app.constraint_matrix_added(end+1,:)=[app.nx*app.ny 1 0 0];        
            app.constraint_matrix_added(end+1,:)=[app.nx*app.ny-app.nx+1 1 0 0];           

            update_constraints(app);

        end

        % Button pushed function: AddFourcornersFixedButton
        function AddFourcornersFixedButtonPushed(app, event)
                        

            app.constraint_matrix_added(end+1,:)=[1 1 1 1];
            app.constraint_matrix_added(end+1,:)=[app.nx 1 1 1];
            app.constraint_matrix_added(end+1,:)=[app.nx*app.ny 1 1 1];        
            app.constraint_matrix_added(end+1,:)=[app.nx*app.ny-app.nx+1 1 1 1];           

            update_constraints(app);
        end

        % Button pushed function: SteinbergButton
        function SteinbergButtonPushed(app, event)
            Steinberg_app;
        end

        % Button pushed function: RainflowButton
        function RainflowButtonPushed(app, event)
            rainflow_app;
        end

        % Callback function: not associated with a component
        function UpdateTotalMassButton_2Pushed(app, event)
            Items = strtrim(app.MassMethodListBox.Items);
            im = strtrim(app.MassMethodListBox.Value);
            app.MassMethod = find(strcmp(Items, im));
            
            update_all_masses(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.rectangularplateFEAUIFigure = uifigure('Visible', 'off');
            app.rectangularplateFEAUIFigure.Name = 'rectangular plate FEA';
            app.vibrationdata_rectangular_plate_fea_appver12byTomIrvineLabel = uilabel(app.rectangularplateFEAUIFigure);
            app.vibrationdata_rectangular_plate_fea_appver12byTomIrvineLabel.Text = 'vibrationdata_rectangular_plate_fea_app   ver 1.2  by Tom Irvine';
            app.TabGroup = uitabgroup(app.rectangularplateFEAUIFigure);
            app.GeometryMaterialTab = uitab(app.TabGroup);
            app.GeometryMaterialTab.Title = 'Geometry & Material';
            app.GenerateMeshButton = uibutton(app.GeometryMaterialTab, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.UnitListBoxLabel = uilabel(app.GeometryMaterialTab);
            app.UnitListBoxLabel.Text = 'Unit';
            app.UnitListBox = uilistbox(app.GeometryMaterialTab);
            app.UnitListBox.Items = {'English', 'metric'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'English';
            app.LengthinEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.LengthinEditFieldLabel.Text = 'Length (in)';
            app.LengthinEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.LengthinEditField.ValueChangingFcn = createCallbackFcn(app, @LengthinEditFieldValueChanging, true);
            app.WidthinEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.WidthinEditFieldLabel.Text = 'Width (in)';
            app.WidthinEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.WidthinEditField.ValueChangingFcn = createCallbackFcn(app, @WidthinEditFieldValueChanging, true);
            app.ThicknessinEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.ThicknessinEditFieldLabel.Text = 'Thickness (in)';
            app.ThicknessinEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.ThicknessinEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessinEditFieldValueChanged, true);
            app.AlongLengthEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.AlongLengthEditFieldLabel.Text = 'Along Length';
            app.AlongLengthEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.AlongLengthEditField.ValueChangingFcn = createCallbackFcn(app, @AlongLengthEditFieldValueChanging, true);
            app.AlongLengthEditField.Value = '17';
            app.AlongWidthEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.AlongWidthEditFieldLabel.Text = 'Along Width';
            app.AlongWidthEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.AlongWidthEditField.ValueChangingFcn = createCallbackFcn(app, @AlongWidthEditFieldValueChanging, true);
            app.AlongWidthEditField.Value = '17';
            app.NumberofNodesLabel = uilabel(app.GeometryMaterialTab);
            app.NumberofNodesLabel.Text = 'Number of Nodes';
            app.MaterialListBoxLabel = uilabel(app.GeometryMaterialTab);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.GeometryMaterialTab);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.ElasticModuluspsiEditFieldLabel.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.ElasticModuluspsiEditField.ValueChangingFcn = createCallbackFcn(app, @ElasticModuluspsiEditFieldValueChanging, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.GeometryMaterialTab);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.GeometryMaterialTab, 'text');
            app.PoissonRatioEditField.ValueChangingFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanging, true);
            app.MassTab_2 = uitab(app.TabGroup);
            app.MassTab_2.SizeChangedFcn = createCallbackFcn(app, @MassTab_2SizeChanged, true);
            app.MassTab_2.Title = 'Mass';
            app.MassTab_2.ButtonDownFcn = createCallbackFcn(app, @MassTab_2ButtonDown, true);
            app.TabGroup4 = uitabgroup(app.MassTab_2);
            app.DistibutedMassTab = uitab(app.TabGroup4);
            app.DistibutedMassTab.Title = 'Distibuted Mass';
            app.MassMethodListBoxLabel = uilabel(app.DistibutedMassTab);
            app.MassMethodListBoxLabel.Text = 'Mass Method';
            app.MassMethodListBox = uilistbox(app.DistibutedMassTab);
            app.MassMethodListBox.Items = {'Density                     ', 'Density + Nonstructural Mass', 'Total Distributed Mass'};
            app.MassMethodListBox.ValueChangedFcn = createCallbackFcn(app, @MassMethodListBoxValueChanged, true);
            app.MassMethodListBox.Value = 'Density                     ';
            app.MassDensitylbmin3EditFieldLabel = uilabel(app.DistibutedMassTab);
            app.MassDensitylbmin3EditFieldLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField = uieditfield(app.DistibutedMassTab, 'text');
            app.MassDensitylbmin3EditField.ValueChangingFcn = createCallbackFcn(app, @MassDensitylbmin3EditFieldValueChanging, true);
            app.UniformNonstructuralMasslbmEditFieldLabel = uilabel(app.DistibutedMassTab);
            app.UniformNonstructuralMasslbmEditFieldLabel.Text = {'Uniform Nonstructural '; 'Mass (lbm)'};
            app.UniformNonstructuralMasslbmEditField = uieditfield(app.DistibutedMassTab, 'text');
            app.UniformNonstructuralMasslbmEditField.ValueChangingFcn = createCallbackFcn(app, @UniformNonstructuralMasslbmEditFieldValueChanging, true);
            app.TotalDistributedMasslbmEditFieldLabel = uilabel(app.DistibutedMassTab);
            app.TotalDistributedMasslbmEditFieldLabel.Text = 'Total Distributed Mass (lbm)';
            app.TotalDistributedMasslbmEditField = uieditfield(app.DistibutedMassTab, 'text');
            app.BarePlateMasslbmEditFieldLabel = uilabel(app.DistibutedMassTab);
            app.BarePlateMasslbmEditFieldLabel.Text = 'Bare Plate Mass (lbm)';
            app.BarePlateMasslbmEditField = uieditfield(app.DistibutedMassTab, 'text');
            app.UpdateTotalMassButton = uibutton(app.DistibutedMassTab, 'push');
            app.UpdateTotalMassButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTotalMassButtonPushed, true);
            app.UpdateTotalMassButton.Text = 'Update Total Mass';
            app.ReadOnlyLabel = uilabel(app.DistibutedMassTab);
            app.ReadOnlyLabel.Text = 'Read Only';
            app.ReadOnlyLabel_2 = uilabel(app.DistibutedMassTab);
            app.ReadOnlyLabel_2.Text = 'Read Only';
            app.PointMassTab = uitab(app.TabGroup4);
            app.PointMassTab.Title = 'Point Mass';
            app.ViewPointMassesButton = uibutton(app.PointMassTab, 'push');
            app.ViewPointMassesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPointMassesButtonPushed, true);
            app.ViewPointMassesButton.Text = 'View Point Masses';
            app.UITablePM = uitable(app.PointMassTab);
            app.UITablePM.ColumnName = {'Node'; 'Mass'};
            app.UITablePM.RowName = {};
            app.NumberofPointMassesLabel = uilabel(app.PointMassTab);
            app.NumberofPointMassesLabel.Text = {'Number of '; 'Point Masses'};
            app.PointMassesListBox = uilistbox(app.PointMassTab);
            app.PointMassesListBox.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.PointMassesListBox.ValueChangedFcn = createCallbackFcn(app, @PointMassesListBoxValueChanged, true);
            app.PointMassesListBox.Value = '0';
            app.MassunitlbmLabel = uilabel(app.PointMassTab);
            app.MassunitlbmLabel.Text = 'Mass unit:  lbm';
            app.ApplyPointMassesButton = uibutton(app.PointMassTab, 'push');
            app.ApplyPointMassesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPointMassesButtonPushed, true);
            app.ApplyPointMassesButton.Text = 'Apply Point Masses';
            app.ResetButton = uibutton(app.PointMassTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UnconstrainedKMTab = uitab(app.TabGroup);
            app.UnconstrainedKMTab.Title = 'Unconstrained K & M';
            app.UnconstrainedKMTab.ButtonDownFcn = createCallbackFcn(app, @UnconstrainedKMTabButtonDown, true);
            app.CalculateKMButton = uibutton(app.UnconstrainedKMTab, 'push');
            app.CalculateKMButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateKMButtonPushed, true);
            app.CalculateKMButton.Text = 'Calculate K & M';
            app.ConstraintsTab = uitab(app.TabGroup);
            app.ConstraintsTab.Title = 'Constraints';
            app.ResetConstraintsButton = uibutton(app.ConstraintsTab, 'push');
            app.ResetConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConstraintsButtonPushed, true);
            app.ResetConstraintsButton.Text = 'Reset Constraints';
            app.UpdateModelViewButton = uibutton(app.ConstraintsTab, 'push');
            app.UpdateModelViewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateModelViewButtonPushed, true);
            app.UpdateModelViewButton.Text = 'Update Model & View';
            app.TabGroup5 = uitabgroup(app.ConstraintsTab);
            app.PerimeterTab = uitab(app.TabGroup5);
            app.PerimeterTab.Title = 'Perimeter';
            app.Image = uiimage(app.PerimeterTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'plate_image.jpg');
            app.TopBCListBoxLabel = uilabel(app.PerimeterTab);
            app.TopBCListBoxLabel.Text = 'Top BC';
            app.TopBCListBox = uilistbox(app.PerimeterTab);
            app.TopBCListBox.Items = {'free', 'pinned', 'fixed'};
            app.TopBCListBox.ValueChangedFcn = createCallbackFcn(app, @TopBCListBoxValueChanged, true);
            app.TopBCListBox.Value = 'free';
            app.RightBCListBoxLabel = uilabel(app.PerimeterTab);
            app.RightBCListBoxLabel.Text = 'Right BC';
            app.RightBCListBox = uilistbox(app.PerimeterTab);
            app.RightBCListBox.Items = {'free', 'pinned', 'fixed'};
            app.RightBCListBox.ValueChangedFcn = createCallbackFcn(app, @RightBCListBoxValueChanged, true);
            app.RightBCListBox.ClickedFcn = createCallbackFcn(app, @RightBCListBoxClicked, true);
            app.RightBCListBox.Value = 'free';
            app.LeftBCListBoxLabel = uilabel(app.PerimeterTab);
            app.LeftBCListBoxLabel.Text = 'Left BC';
            app.LeftBCListBox = uilistbox(app.PerimeterTab);
            app.LeftBCListBox.Items = {'free', 'pinned', 'fixed'};
            app.LeftBCListBox.ValueChangedFcn = createCallbackFcn(app, @LeftBCListBoxValueChanged, true);
            app.LeftBCListBox.ClickedFcn = createCallbackFcn(app, @LeftBCListBoxClicked, true);
            app.LeftBCListBox.Value = 'free';
            app.BottomBCListBoxLabel = uilabel(app.PerimeterTab);
            app.BottomBCListBoxLabel.Text = 'Bottom BC';
            app.BottomBCListBox = uilistbox(app.PerimeterTab);
            app.BottomBCListBox.Items = {'free', 'pinned', 'fixed'};
            app.BottomBCListBox.ValueChangedFcn = createCallbackFcn(app, @BottomBCListBoxValueChanged, true);
            app.BottomBCListBox.Value = 'free';
            app.ManuallyAddNodesTab = uitab(app.TabGroup5);
            app.ManuallyAddNodesTab.Title = 'Manually Add Nodes';
            app.ConstraintTypeListBoxLabel = uilabel(app.ManuallyAddNodesTab);
            app.ConstraintTypeListBoxLabel.Text = 'Constraint Type';
            app.ConstraintTypeListBox = uilistbox(app.ManuallyAddNodesTab);
            app.ConstraintTypeListBox.Items = {'Fixed', 'TZ only'};
            app.ConstraintTypeListBox.Value = 'TZ only';
            app.ManuallyAddNodeNumberEditFieldLabel = uilabel(app.ManuallyAddNodesTab);
            app.ManuallyAddNodeNumberEditFieldLabel.Text = {'Manually Add '; 'Node Number'};
            app.ManuallyAddNodeNumberEditField = uieditfield(app.ManuallyAddNodesTab, 'text');
            app.AddManualButton = uibutton(app.ManuallyAddNodesTab, 'push');
            app.AddManualButton.ButtonPushedFcn = createCallbackFcn(app, @AddManualButtonPushed, true);
            app.AddManualButton.Text = 'Add Manual';
            app.AddFourcornersTZButton = uibutton(app.ManuallyAddNodesTab, 'push');
            app.AddFourcornersTZButton.ButtonPushedFcn = createCallbackFcn(app, @AddFourcornersTZButtonPushed, true);
            app.AddFourcornersTZButton.Text = 'Add Four corners TZ';
            app.AddFourcornersFixedButton = uibutton(app.ManuallyAddNodesTab, 'push');
            app.AddFourcornersFixedButton.ButtonPushedFcn = createCallbackFcn(app, @AddFourcornersFixedButtonPushed, true);
            app.AddFourcornersFixedButton.Text = 'Add Four corners Fixed';
            app.Constrainednodes0Free1ConstrainedLabel = uilabel(app.ConstraintsTab);
            app.Constrainednodes0Free1ConstrainedLabel.Text = 'Constrained nodes        0=Free  1=Constrained';
            app.UITableConstraints = uitable(app.ConstraintsTab);
            app.UITableConstraints.ColumnName = {'Node'; 'TZ'; 'RX'; 'RY'};
            app.UITableConstraints.RowName = {};
            app.UITableConstraints.ColumnEditable = [false false false false];
            app.NaturalFreqTab = uitab(app.TabGroup);
            app.NaturalFreqTab.SizeChangedFcn = createCallbackFcn(app, @NaturalFreqTabSizeChanged, true);
            app.NaturalFreqTab.Title = 'Natural Freq';
            app.NaturalFreqTab.ButtonDownFcn = createCallbackFcn(app, @NaturalFreqTabButtonDown, true);
            app.CalculatefnButton = uibutton(app.NaturalFreqTab, 'push');
            app.CalculatefnButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatefnButtonPushed, true);
            app.CalculatefnButton.Text = 'Calculate fn';
            app.UITable_fn = uitable(app.NaturalFreqTab);
            app.UITable_fn.ColumnName = {'fn (Hz)'; 'Part. Factor'; 'Modal Mass %'};
            app.UITable_fn.RowName = {};
            app.PlotModeShapeButton = uibutton(app.NaturalFreqTab, 'push');
            app.PlotModeShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModeShapeButtonPushed, true);
            app.PlotModeShapeButton.Text = 'Plot Mode Shape';
            app.ModeListBoxLabel = uilabel(app.NaturalFreqTab);
            app.ModeListBoxLabel.Text = 'Mode';
            app.ModeListBox = uilistbox(app.NaturalFreqTab);
            app.ModeListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ModeListBox.Value = '1';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.SizeChangedFcn = createCallbackFcn(app, @DampingTabSizeChanged, true);
            app.DampingTab.Title = 'Damping';
            app.DampingTab.ButtonDownFcn = createCallbackFcn(app, @DampingTabButtonDown, true);
            app.DampingTypeListBoxLabel = uilabel(app.DampingTab);
            app.DampingTypeListBoxLabel.Text = 'Damping Type';
            app.DampingTypeListBox = uilistbox(app.DampingTab);
            app.DampingTypeListBox.Items = {'Amplification Factor Q', 'Viscous Damping Ratio'};
            app.DampingTypeListBox.ValueChangedFcn = createCallbackFcn(app, @DampingTypeListBoxValueChanged, true);
            app.DampingTypeListBox.Value = 'Amplification Factor Q';
            app.ModalDistributionListBoxLabel = uilabel(app.DampingTab);
            app.ModalDistributionListBoxLabel.Text = 'Modal Distribution';
            app.ModalDistributionListBox = uilistbox(app.DampingTab);
            app.ModalDistributionListBox.Items = {'Uniform', 'Varies by Mode'};
            app.ModalDistributionListBox.ValueChangedFcn = createCallbackFcn(app, @ModalDistributionListBoxValueChanged, true);
            app.ModalDistributionListBox.Value = 'Uniform';
            app.UniformQLabel = uilabel(app.DampingTab);
            app.UniformQLabel.Text = 'Uniform Q';
            app.UniformQEditField = uieditfield(app.DampingTab, 'text');
            app.SaveDampingButton = uibutton(app.DampingTab, 'push');
            app.SaveDampingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDampingButtonPushed, true);
            app.SaveDampingButton.Text = 'Save Damping';
            app.UITable_damping = uitable(app.DampingTab);
            app.UITable_damping.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable_damping.RowName = {};
            app.BaseExcitationTab = uitab(app.TabGroup);
            app.BaseExcitationTab.SizeChangedFcn = createCallbackFcn(app, @BaseExcitationTabSizeChanged, true);
            app.BaseExcitationTab.Title = 'Base Excitation';
            app.BaseExcitationTab.ButtonDownFcn = createCallbackFcn(app, @BaseExcitationTabButtonDown, true);
            app.TabGroup2 = uitabgroup(app.BaseExcitationTab);
            app.PartitionMatricesTab = uitab(app.TabGroup2);
            app.PartitionMatricesTab.Title = 'Partition Matrices';
            app.PartitionMatricesTab.ButtonDownFcn = createCallbackFcn(app, @PartitionMatricesTabButtonDown, true);
            app.PartitionButton = uibutton(app.PartitionMatricesTab, 'push');
            app.PartitionButton.ButtonPushedFcn = createCallbackFcn(app, @PartitionButtonPushed, true);
            app.PartitionButton.Text = 'Partition';
            app.TransmissibilityTab = uitab(app.TabGroup2);
            app.TransmissibilityTab.Title = 'Transmissibility';
            app.TransmissibilityTab.ButtonDownFcn = createCallbackFcn(app, @TransmissibilityTabButtonDown, true);
            app.FrequencyHzLimitsLabel = uilabel(app.TransmissibilityTab);
            app.FrequencyHzLimitsLabel.Text = 'Frequency (Hz) Limits';
            app.MinEditFieldLabel = uilabel(app.TransmissibilityTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField_trans = uieditfield(app.TransmissibilityTab, 'text');
            app.MinEditField_trans.Value = '10';
            app.MaxEditField_2Label = uilabel(app.TransmissibilityTab);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_trans = uieditfield(app.TransmissibilityTab, 'text');
            app.MaxEditField_trans.Value = '2000';
            app.CalculateTransmissibilityButton = uibutton(app.TransmissibilityTab, 'push');
            app.CalculateTransmissibilityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTransmissibilityButtonPushed, true);
            app.CalculateTransmissibilityButton.Text = 'Calculate Transmissibility';
            app.TransOutputArraysReadOnlyTextAreaLabel = uilabel(app.TransmissibilityTab);
            app.TransOutputArraysReadOnlyTextAreaLabel.Text = 'Trans Output Arrays, Read Only';
            app.TransOutputArraysReadOnlyTextArea = uitextarea(app.TransmissibilityTab);
            app.TransOutputArraysReadOnlyTextArea.Editable = 'off';
            app.OutputFrequencySpacingListBoxLabel = uilabel(app.TransmissibilityTab);
            app.OutputFrequencySpacingListBoxLabel.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox = uilistbox(app.TransmissibilityTab);
            app.OutputFrequencySpacingListBox.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave'};
            app.OutputFrequencySpacingListBox.Value = 'Constant 1.0 Hz';
            app.SineTab = uitab(app.TabGroup2);
            app.SineTab.Title = 'Sine';
            app.SineTab.ButtonDownFcn = createCallbackFcn(app, @SineTabButtonDown, true);
            app.CalculateSineResponseButton = uibutton(app.SineTab, 'push');
            app.CalculateSineResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSineResponseButtonPushed, true);
            app.CalculateSineResponseButton.Text = 'Calculate Sine Response';
            app.FreqHzEditFieldLabel = uilabel(app.SineTab);
            app.FreqHzEditFieldLabel.Text = 'Freq  (Hz)';
            app.FreqHzEditField = uieditfield(app.SineTab, 'text');
            app.FreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanging, true);
            app.ResponsetoSineBaseInputLabel = uilabel(app.SineTab);
            app.ResponsetoSineBaseInputLabel.Text = 'Response to Sine Base Input';
            app.ExcitationLabel = uilabel(app.SineTab);
            app.ExcitationLabel.Text = 'Excitation';
            app.AccelGEditFieldLabel = uilabel(app.SineTab);
            app.AccelGEditFieldLabel.Text = 'Accel (G)';
            app.AccelGEditField = uieditfield(app.SineTab, 'text');
            app.AccelGEditField.ValueChangingFcn = createCallbackFcn(app, @AccelGEditFieldValueChanging, true);
            app.AccelGEditField.Value = '1';
            app.SineResponseTextAreaLabel = uilabel(app.SineTab);
            app.SineResponseTextAreaLabel.Text = 'Sine Response';
            app.SineResponseTextArea = uitextarea(app.SineTab);
            app.SettoFundamentalFrequencyButton = uibutton(app.SineTab, 'push');
            app.SettoFundamentalFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @SettoFundamentalFrequencyButtonPushed, true);
            app.SettoFundamentalFrequencyButton.Text = 'Set to Fundamental Frequency ';
            app.PSDTab = uitab(app.TabGroup2);
            app.PSDTab.Title = 'PSD';
            app.PSDTab.ButtonDownFcn = createCallbackFcn(app, @PSDTabButtonDown, true);
            app.CalculatePSDResponseButton = uibutton(app.PSDTab, 'push');
            app.CalculatePSDResponseButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePSDResponseButtonPushed, true);
            app.CalculatePSDResponseButton.Text = 'Calculate PSD Response';
            app.listbox_method_psd = uilistbox(app.PSDTab);
            app.listbox_method_psd.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_psd.ValueChangedFcn = createCallbackFcn(app, @listbox_method_psdValueChanged, true);
            app.listbox_method_psd.Tag = 'listbox_method';
            app.listbox_method_psd.Value = 'Array Preloaded in Matlab';
            app.text1_psd = uilabel(app.PSDTab);
            app.text1_psd.Tag = 'text1';
            app.text1_psd.Text = 'Select Data Input Method';
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel = uilabel(app.PSDTab);
            app.TheinputarraymusthavetwocolumnsFreqHzAccelG2HzLabel.Text = {'The input array must have two columns:  Freq (Hz) & Accel (G^2/Hz)'; ''};
            app.UpdateListButton_psd = uibutton(app.PSDTab, 'push');
            app.UpdateListButton_psd.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_psdPushed, true);
            app.UpdateListButton_psd.Text = 'Update List';
            app.SelectInputArrayListBox_2Label = uilabel(app.PSDTab);
            app.SelectInputArrayListBox_2Label.Text = 'Select Input Array';
            app.SelectInputArrayListBox_psd = uilistbox(app.PSDTab);
            app.SelectInputArrayListBox_psd.Items = {'Item 1'};
            app.OutputFrequencySpacingListBox_2Label = uilabel(app.PSDTab);
            app.OutputFrequencySpacingListBox_2Label.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox_psd = uilistbox(app.PSDTab);
            app.OutputFrequencySpacingListBox_psd.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave', '1/48 octave'};
            app.OutputFrequencySpacingListBox_psd.Value = 'Constant 1.0 Hz';
            app.PSDOutputArraysReadOnlyTextAreaLabel = uilabel(app.PSDTab);
            app.PSDOutputArraysReadOnlyTextAreaLabel.Text = 'PSD Output Arrays, Read Only';
            app.PSDOutputArraysReadOnlyTextArea = uitextarea(app.PSDTab);
            app.PSDOutputArraysReadOnlyTextArea.Editable = 'off';
            app.ArbitraryTimeHistoryTab = uitab(app.TabGroup2);
            app.ArbitraryTimeHistoryTab.SizeChangedFcn = createCallbackFcn(app, @ArbitraryTimeHistoryTabSizeChanged, true);
            app.ArbitraryTimeHistoryTab.Title = 'Arbitrary Time History';
            app.ArbitraryTimeHistoryTab.ButtonDownFcn = createCallbackFcn(app, @ArbitraryTimeHistoryTabButtonDown, true);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel = uilabel(app.ArbitraryTimeHistoryTab);
            app.TheinputarraymusthavetwocolumnstimesecaccelGLabel.Text = 'The input array must have two columns:  time (sec) & accel (G)';
            app.CalculateBaseInputArbitrary = uibutton(app.ArbitraryTimeHistoryTab, 'push');
            app.CalculateBaseInputArbitrary.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputArbitraryButtonPushed, true);
            app.CalculateBaseInputArbitrary.Text = 'Calculate';
            app.listbox_method = uilistbox(app.ArbitraryTimeHistoryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.ArbitraryTimeHistoryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.ArbitraryTimeHistoryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.ArbitraryTimeHistoryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.ArbitraryTimeHistoryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ResponseResultsReadOnlyLabel = uilabel(app.ArbitraryTimeHistoryTab);
            app.ResponseResultsReadOnlyLabel.Text = {'Response Results '; 'Read Only'};
            app.ResponseResultsReadOnlyTextArea = uitextarea(app.ArbitraryTimeHistoryTab);
            app.ResponseResultsReadOnlyTextArea.Editable = 'off';
            app.MDOFSRSTab = uitab(app.TabGroup2);
            app.MDOFSRSTab.Title = 'MDOF SRS';
            app.MDOFSRSTab.ButtonDownFcn = createCallbackFcn(app, @MDOFSRSTabButtonDown, true);
            app.CalculateBaseInputMDOFSRS = uibutton(app.MDOFSRSTab, 'push');
            app.CalculateBaseInputMDOFSRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateBaseInputMDOFSRSButtonPushed, true);
            app.CalculateBaseInputMDOFSRS.Text = 'Calculate';
            app.listbox_method_srs = uilistbox(app.MDOFSRSTab);
            app.listbox_method_srs.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_srs.ValueChangedFcn = createCallbackFcn(app, @listbox_method_srsValueChanged, true);
            app.listbox_method_srs.Tag = 'listbox_method';
            app.listbox_method_srs.Value = 'Array Preloaded in Matlab';
            app.text1_srs = uilabel(app.MDOFSRSTab);
            app.text1_srs.Tag = 'text1';
            app.text1_srs.Text = 'Select Data Input Method';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel = uilabel(app.MDOFSRSTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSGLabel.Text = {'The input SRS must have two columns: Natural Frequency (Hz) & SRS (G)'; ''};
            app.UpdateListButton_srs = uibutton(app.MDOFSRSTab, 'push');
            app.UpdateListButton_srs.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_srsPushed, true);
            app.UpdateListButton_srs.Text = 'Update List';
            app.SpecificationQEditFieldLabel = uilabel(app.MDOFSRSTab);
            app.SpecificationQEditFieldLabel.Text = 'Specification Q';
            app.SpecificationQEditField = uieditfield(app.MDOFSRSTab, 'numeric');
            app.SpecificationQEditField.Value = 10;
            app.SelectInputArrayListBox_2Label_2 = uilabel(app.MDOFSRSTab);
            app.SelectInputArrayListBox_2Label_2.Text = 'Select Input Array';
            app.SelectInputArrayListBox_srs = uilistbox(app.MDOFSRSTab);
            app.SelectInputArrayListBox_srs.Items = {'Item 1'};
            app.ResultsTextAreaLabel_srs = uilabel(app.MDOFSRSTab);
            app.ResultsTextAreaLabel_srs.Text = 'SRS Results, Read Only';
            app.ResultsTextArea_srs = uitextarea(app.MDOFSRSTab);
            app.ResultsTextArea_srs.Editable = 'off';
            app.NumberofModestoIncludeListBoxLabel = uilabel(app.BaseExcitationTab);
            app.NumberofModestoIncludeListBoxLabel.Text = {'Number of Modes '; 'to Include'};
            app.NumberofModestoIncludeListBox = uilistbox(app.BaseExcitationTab);
            app.NumberofModestoIncludeListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModestoIncludeListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofModestoIncludeListBoxValueChanged, true);
            app.NumberofModestoIncludeListBox.Value = '1';
            app.ResponseNodeEditFieldLabel = uilabel(app.BaseExcitationTab);
            app.ResponseNodeEditFieldLabel.Text = 'Response Node';
            app.ResponseNodeEditField = uieditfield(app.BaseExcitationTab, 'text');
            app.ResponseNodeEditField.ValueChangingFcn = createCallbackFcn(app, @ResponseNodeEditFieldValueChanging, true);
            app.SetNodetoListBoxLabel = uilabel(app.BaseExcitationTab);
            app.SetNodetoListBoxLabel.Text = 'Set Node to';
            app.SetNodetoListBox = uilistbox(app.BaseExcitationTab);
            app.SetNodetoListBox.Items = {'Nearest to Center', 'Max Eigen Mode 1', 'Max Eigen Mode 2', 'Max Eigen Mode 3'};
            app.SetNodetoListBox.ValueChangedFcn = createCallbackFcn(app, @SetNodetoListBoxValueChanged, true);
            app.SetNodetoListBox.Value = 'Nearest to Center';
            app.AppliedPressureTab = uitab(app.TabGroup);
            app.AppliedPressureTab.Title = 'Applied Pressure';
            app.AppliedPressureTab.ButtonDownFcn = createCallbackFcn(app, @AppliedPressureTabButtonDown, true);
            app.TabGroup3 = uitabgroup(app.AppliedPressureTab);
            app.UniformFullyCorrelatedPressureTransferFunctionTab = uitab(app.TabGroup3);
            app.UniformFullyCorrelatedPressureTransferFunctionTab.Title = 'Uniform, Fully Correlated Pressure, Transfer Function';
            app.UniformFullyCorrelatedPressureTransferFunctionTab.ButtonDownFcn = createCallbackFcn(app, @UniformFullyCorrelatedPressureTransferFunctionTabButtonDown, true);
            app.FrequencyHzLimitsLabel_pressure = uilabel(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.FrequencyHzLimitsLabel_pressure.Text = 'Frequency (Hz) Limits';
            app.CalculateTransmissibilityButton_pressure = uibutton(app.UniformFullyCorrelatedPressureTransferFunctionTab, 'push');
            app.CalculateTransmissibilityButton_pressure.ButtonPushedFcn = createCallbackFcn(app, @CalculateTransmissibilityButton_pressurePushed, true);
            app.CalculateTransmissibilityButton_pressure.Text = 'Calculate Transfer Function';
            app.OutputFrequencySpacingListBox_2Label_2 = uilabel(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.OutputFrequencySpacingListBox_2Label_2.Text = 'Output Frequency Spacing';
            app.OutputFrequencySpacingListBox_pressure = uilistbox(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.OutputFrequencySpacingListBox_pressure.Items = {'Constant 1.0 Hz', 'Constant 0.5 Hz', '1/96 octave'};
            app.OutputFrequencySpacingListBox_pressure.Value = 'Constant 1.0 Hz';
            app.MinEditFieldLabel_2 = uilabel(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.MinEditField_trans_pressure = uieditfield(app.UniformFullyCorrelatedPressureTransferFunctionTab, 'text');
            app.MinEditField_trans_pressure.Value = '10';
            app.MaxEditField_2Label_2 = uilabel(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.MaxEditField_2Label_2.Text = 'Max';
            app.MaxEditField_trans_pressure = uieditfield(app.UniformFullyCorrelatedPressureTransferFunctionTab, 'text');
            app.MaxEditField_trans_pressure.Value = '2000';
            app.TransOutputArraysReadOnlyTextArea_2Label = uilabel(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.TransOutputArraysReadOnlyTextArea_2Label.Text = 'Trans Output Arrays, Read Only';
            app.TransOutputArraysReadOnlyTextArea_pressure = uitextarea(app.UniformFullyCorrelatedPressureTransferFunctionTab);
            app.TransOutputArraysReadOnlyTextArea_pressure.Editable = 'off';
            app.PressurePSDResponseTab = uitab(app.TabGroup3);
            app.PressurePSDResponseTab.Title = 'Pressure PSD Response';
            app.TobeaddedinfuturerevisionLabel = uilabel(app.PressurePSDResponseTab);
            app.TobeaddedinfuturerevisionLabel.Text = 'To be added in future revision';
            app.NumberofModestoIncludeListBox_2Label = uilabel(app.AppliedPressureTab);
            app.NumberofModestoIncludeListBox_2Label.Text = {'Number of Modes '; 'to Include'};
            app.NumberofModestoIncludeListBox_pressure = uilistbox(app.AppliedPressureTab);
            app.NumberofModestoIncludeListBox_pressure.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumberofModestoIncludeListBox_pressure.Value = '1';
            app.ResponseNodeEditField_2Label = uilabel(app.AppliedPressureTab);
            app.ResponseNodeEditField_2Label.Text = 'Response Node';
            app.ResponseNodeEditField_pressure = uieditfield(app.AppliedPressureTab, 'text');
            app.SetNodetoListBox_2Label = uilabel(app.AppliedPressureTab);
            app.SetNodetoListBox_2Label.Text = 'Set Node to';
            app.SetNodetoListBox_pressure = uilistbox(app.AppliedPressureTab);
            app.SetNodetoListBox_pressure.Items = {'Nearest to Center', 'Max Eigen Mode 1', 'Max Eigen Mode 2', 'Max Eigen Mode 3'};
            app.SetNodetoListBox_pressure.ValueChangedFcn = createCallbackFcn(app, @SetNodetoListBox_pressureValueChanged, true);
            app.SetNodetoListBox_pressure.Value = 'Nearest to Center';
            app.ViewMeshButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.ViewMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMeshButtonPushed, true);
            app.ViewMeshButton.Text = 'View Mesh';
            app.ViewConstraintsButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.ViewConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewConstraintsButtonPushed, true);
            app.ViewConstraintsButton.Text = 'View Constraints';
            app.ReturnButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SaveModelButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.ReadOnlyPanel = uipanel(app.rectangularplateFEAUIFigure);
            app.ReadOnlyPanel.Title = 'Read Only';
            app.NodeNearestCenterEditFieldLabel = uilabel(app.ReadOnlyPanel);
            app.NodeNearestCenterEditFieldLabel.Text = 'Node Nearest Center';
            app.NodeNearestCenterEditField = uieditfield(app.ReadOnlyPanel, 'text');
            app.TotalMasslbmEditFieldLabel = uilabel(app.ReadOnlyPanel);
            app.TotalMasslbmEditFieldLabel.Text = 'Total Mass (lbm)';
            app.TotalMasslbmEditField = uieditfield(app.ReadOnlyPanel, 'text');
            app.ModelNameEditFieldLabel = uilabel(app.ReadOnlyPanel);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.ReadOnlyPanel, 'text');
            app.ModelNameEditField.Editable = 'off';
            app.ResetButton_2 = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.SteinbergButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.SteinbergButton.ButtonPushedFcn = createCallbackFcn(app, @SteinbergButtonPushed, true);
            app.SteinbergButton.Text = 'Steinberg';
            app.RainflowButton = uibutton(app.rectangularplateFEAUIFigure, 'push');
            app.RainflowButton.ButtonPushedFcn = createCallbackFcn(app, @RainflowButtonPushed, true);
            app.RainflowButton.Text = 'Rainflow';
            % Show the figure after all components are created
            app.rectangularplateFEAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_rectangular_plate_fea_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.rectangularplateFEAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.rectangularplateFEAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rectangularplateFEAUIFigure)
        end
    end
end