classdef vibrationdata_sdof_base_Apph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sdofbaseUIFigure                matlab.ui.Figure
        UpdateListButton                matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        text1                           matlab.ui.control.Label
        pushbutton_return               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        pushbutton_calculate            matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        AmplificationFactorQEditField_2  matlab.ui.control.EditField
        AmplificationFactorQEditField_2Label  matlab.ui.control.Label
        NaturalFrequencyHzEditField_2   matlab.ui.control.EditField
        NaturalFrequencyHzEditField_2Label  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        text10                          matlab.ui.control.Label
        listbox_unit                    matlab.ui.control.ListBox
        PlotTypesTab                    matlab.ui.container.Tab
        ResponseAccelEnvelopesCheckBox  matlab.ui.control.CheckBox
        ResponseAccelPeakValueHistogramCheckBox  matlab.ui.control.CheckBox
        ResponseAccelInstantaneousHistogramCheckBox  matlab.ui.control.CheckBox
        RelativeDisplacementCheckBox    matlab.ui.control.CheckBox
        BothTimeHistoriesHistogramsCheckBox  matlab.ui.control.CheckBox
        BothTimeHistoriesCheckBox       matlab.ui.control.CheckBox
        AccelResponseCheckBox           matlab.ui.control.CheckBox
        BaseInputCheckBox               matlab.ui.control.CheckBox
        ExportPostprocessTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ExportTab                       matlab.ui.container.Tab
        SelectOutputListBox             matlab.ui.control.ListBox
        SelectOutputListBoxLabel        matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        edit_output_array               matlab.ui.control.EditField
        pushbutton_save                 matlab.ui.control.Button
        PSDTransTab                     matlab.ui.container.Tab
        pushbutton_psd_2                matlab.ui.control.Button
        pushbutton_psd                  matlab.ui.control.Button
        RainflowFatigueTab              matlab.ui.container.Tab
        pushbutton_rainflow_fatigue     matlab.ui.control.Button
        SteinbergFatigueTab             matlab.ui.container.Tab
        CumulativeDamageIndexTextArea   matlab.ui.control.TextArea
        CumulativeDamageIndexTextAreaLabel  matlab.ui.control.Label
        inch3sigmaLabel                 matlab.ui.control.Label
        Zlimitfor20millioncyclesEditField  matlab.ui.control.EditField
        Zlimitfor20millioncyclesEditFieldLabel  matlab.ui.control.Label
        RelDispUnitListBox              matlab.ui.control.ListBox
        RelDispUnitListBoxLabel         matlab.ui.control.Label
        pushbutton_calculate_2          matlab.ui.control.Button
        SelectrelativedisplacementinputarrayfromleftlistboxLabel  matlab.ui.control.Label
        Notethattheamplitudeintheresultingtableispeakvalley2Label  matlab.ui.control.Label
        RainflowcyclecountingperASTME1049852005Label  matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM            % Description
        nMethod        %
        accel_data     %
        rel_disp_data  % 
        nRDU           %
    end
    
    methods (Access = private)

        function Clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.CumulativeDamageIndexTextArea.Visible='off';
            app.CumulativeDamageIndexTextAreaLabel.Visible='off';         
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};
        end
        function RelDispUnit(app)
            Items = char(app.RelDispUnitListBox.Items);
            im=app.RelDispUnitListBox.Value;
            [app.nRDU]=find_index(Items,im);

            if(app.nRDU==1)
                app.inch3sigmaLabel.Text='inch (3-sigma)';
            else
                app.inch3sigmaLabel.Text='mm (3-sigma)';            
            end
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Clear(app);
            update(app);
            RelDispUnit(app);

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethod]=find_index(Items,im);           
        end

        % Button pushed function: pushbutton_return
        function pushbutton_returnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Clear(app);

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.nMethod]=find_index(Items,im);

            if(app.nMethod==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM);

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculateButtonPushed(app, event)
            ClosePlots(app);
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            Items = char(app.listbox_unit.Items);
            im=app.listbox_unit.Value;
            [iu]=find_index(Items,im);

            app.nMethod

            if(app.nMethod==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            sz=size(app.THM);
            
            if(sz(2)~=2)
               warndlg('Input array must have two columns');
               return;
            end
            
            [app.THM,iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(iflag==999)
                return;
            end
            
            y=double(app.THM(:,2));
            yy=y;
            
            dt=mean(diff(app.THM(:,1)));
   
            Q=str2num(app.AmplificationFactorQEditField_2.Value);
            fn=str2num(app.NaturalFrequencyHzEditField_2.Value);
            
            if(isempty(fn)==1)
                warndlg('Enter natural frequency');
                return;
            end

            damp=1/(2*Q);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);
            %
            %  SRS engine
            %
            
            a_pos=zeros(1,1);
            a_neg=zeros(1,1);
            rd_pos=zeros(1,1);
            rd_neg=zeros(1,1);
            
            for j=1:1
            %
                disp(' ')
                disp(' Calculating absolute acceleration');
            %
                [a_resp,a_pos(j),a_neg(j)]=arbit_engine(a1(j),a2(j),b1(j),b2(j),b3(j),yy);
            
                zdd=a_resp-y;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ')
                disp(' Calculating relative displacement');
            %
                [rd_resp,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),yy);
            
                z=rd_resp;
            
                omegan=2*pi*fn;
                tdo=2*damp*omegan;
            %
                om2=omegan^2;
            
                zd=(-yy-zdd-om2*z)/tdo;
            
                EK=0;
                ED=0;
                EA=0;
            
                for i=1:length(z)
                    EK=EK+zdd(i)*zd(i);
                    ED=ED+tdo*zd(i)^2;
                    EA=EA+om2*z(i)*zd(i);
                end
            %
                EK=EK*dt;
                ED=ED*dt;
                EA=EA*dt;
            %
            %
                if(iu==1)
                    scale=386;
                end
                if(iu==2)
                    scale=9.81*1000;
                end
                if(iu==3)
                    scale=1000;
                end
            %
                rd_resp=rd_resp*scale;
                rd_pos(j)=rd_pos(j)*scale;
                rd_neg(j)=rd_neg(j)*scale;
            
                if(iu==1)
                    escale=386^2;
                    slabel='(in/sec)^2';
                end
                if(iu==2)
                    escale=9.81^2;
                    slabel='(m/sec)^2';
                end
                if(iu==3)
                    escale=1;
                    slabel='(m/sec)^2';
                end
            
                EK=EK*escale;
                ED=ED*escale;
                EA=EA*escale;
            %
                EI=EK+ED+EA;
            
                EK=abs(EK);
                ED=abs(ED);
                EA=abs(EA);
                EI=abs(EI);
            
                ss1='Kinetic Energy = ';
                ss2='Dissipated Energy = ';
                ss3='Absorbed Energy = ';
                ss4='Total Energy = ';
            
            end
            %
     
            [~,a_sd,a_rms,sk,kt]=kurtosis_stats(a_resp);
            [~,~,rd_rms,~,~]=kurtosis_stats(rd_resp);
            
            %
            if(iu==1 || iu==2)
                big_string=sprintf(' Acceleration Response (G) \n');
            else
                big_string=sprintf(' Acceleration Response (m/sec^2) \n');
            end
            
            crest=(max([a_pos(1) abs(a_neg(1))]))/a_sd;
            
            dur=app.THM(end,1)-app.THM(1,1);
            
            [pszcr,peak_rate,~,pa]=zero_crossing_function_alt(app.THM(:,1),a_resp,dur);
            [rf]=Rice_frequency(a_resp,dt);
            
            string1=sprintf('\n\n max=%8.4g',a_pos(1));
            string2=sprintf('   min=%8.4g',-a_neg(1));
            string3=sprintf('\n\n RMS=%8.4g',a_rms);
            string4=sprintf('   std dev=%8.4g',std(a_resp));
            string5=sprintf('\n mean=%8.4g',mean(a_resp));
            string6=sprintf('\n\n crest factor=%7.3g',crest);
            string7=sprintf('\n skewness=%7.3g',sk);
            string8=sprintf('   kurtosis=%7.3g',kt);
            
            string_rf=sprintf('\n\n Rice Characteristic Frequency = %8.4g Hz ',rf);
            string_zc=sprintf('\n Positive Slope Zero Cross Rate = %8.4g Hz ',pszcr);
            string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            big_string=strcat(big_string,string1);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            
            if(iu==1)
                string8=sprintf('\n\n Relative Displacement (in) \n');
            else
                string8=sprintf('\n\n Relative Displacement (mm) \n');
            end
            
            string9=sprintf('\n\n max=%7.3g',rd_pos(1));
            string10=sprintf('    min=%7.3g',rd_neg(1));
            string11=sprintf('    RMS=%7.3g',rd_rms);
            
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);
            big_string=strcat(big_string,string11);
            
            big_string=strcat(big_string,string_rf);
            big_string=strcat(big_string,string_zc);
            big_string=strcat(big_string,string_pr);
            
            ss1='Kinetic Energy/Mass = ';
            ss2='Dissipated Energy/Mass = ';
            ss3='Absorbed Energy/Mass = ';
            ss4='Total Energy/Mass = ';
            
            sss=sprintf('\n\n %s %8.4g %s \n %s %8.4g %s \n %s %8.4g %s \n %s %8.4g %s',ss1,EK,slabel,ss2,ED,slabel,ss3,EA,slabel,ss4,EI,slabel);
            
            big_string=strcat(big_string,sss);
            
            
            out12=sprintf('\n Q=%g ',Q);
            disp(out12);
            
            spa=pa/a_sd;
            npa=length(pa);
            
              L3=length(spa(spa > 3.0));
            L3p5=length(spa(spa > 3.5));
              L4=length(spa(spa > 4.0));
            L4p5=length(spa(spa > 4.5));
              L5=length(spa(spa > 5.0));
            L5p5=length(spa(spa > 5.5));
              L6=length(spa(spa > 6.0));
            
            disp(' ');
            disp(' Number of Peaks >= n-sigma');
            disp(' ');
            
            out1=sprintf(' 3.0-sigma    %d  or %7.3g percent',L3,100*L3/npa);
            out2=sprintf(' 3.5-sigma    %d  or %7.3g percent',L3p5,100*L3p5/npa);
            out3=sprintf(' 4.0-sigma    %d  or %7.3g percent',L4,100*L4/npa);
            out4=sprintf(' 4.5-sigma    %d  or %7.3g percent',L4p5,100*L4p5/npa);
            out5=sprintf(' 5.0-sigma    %d  or %7.3g percent',L5,100*L5/npa);
            out6=sprintf(' 5.5-sigma    %d  or %7.3g percent',L5p5,100*L5p5/npa);
            out7=sprintf(' 6.0-sigma    %d  or %7.3g percent',L6,100*L6/npa);
            
            disp(out1);
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            disp(out6);
            disp(out7);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Acceleration Input & Response Statistics ');
            disp(' ');
            
            [rho]=Pearson_coefficient(yy,a_resp);
            
            out1=sprintf(' Pearson product-moment correlation coefficient = %8.4g ',rho);
            disp(out1);

            sss=sprintf('\n\n Pearson product-moment \n correlation coefficient = %8.4g ',rho);
            
            big_string=strcat(big_string,sss);

            disp(' ');
            disp(big_string);

            app.ResultsTextArea.Value=big_string;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';            
            
            disp(' ');
            disp(' Covariance Matrix: ');
            disp(' ');
            
            cvm = cov(yy,a_resp)
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            app.accel_data=[app.THM(:,1) a_resp];
            app.rel_disp_data=[app.THM(:,1) rd_resp];


            %
            fig_num=1;
            %
            data1=app.THM;
            data2=[app.THM(:,1),a_resp];
            xlabel2=' Time(sec) ';
            nbars=31;
            
            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),a_resp);
            xlabel(' Time(sec) ');
            legend('Base Input','Response');
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')
            end
            grid on;
            title('Acceleration Time History');
            
            if(app.BaseInputCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),app.THM(:,2));
            
                title('Base Input Acceleration Time History');
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            end
            
            if(app.AccelResponseCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),a_resp);
                out1=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.BothTimeHistoriesCheckBox.Value)
            
                if(iu==1 || iu==2)
                    ylabel1='Accel (G)';
                    ylabel2='Accel (G)';
                else
                    ylabel1='Accel (m/sec^2)';
                    ylabel2='Accel (m/sec^2)';
                end
            
                t_string1=('Base Input');
                t_string2=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
            
                [fig_num]=...
                subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel2,...
                                                      data1,data2,t_string1,t_string2);
            end
            
            if(app.BothTimeHistoriesHistogramsCheckBox.Value)
                nbars=31;
            
                [fig_num]=plot_two_time_histories_histograms(fig_num,xlabel2,...
                                ylabel1,ylabel2,data1,data2,t_string1,t_string2,nbars);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.RelativeDisplacementCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),rd_resp);
                out1=sprintf('Relative Displacement fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1)
                    ylabel('Rel Disp (in)')
                else
                    ylabel('Rel Disp (mm)')
                end
                grid on;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.ResponseAccelInstantaneousHistogramCheckBox.Value)
            
                xx=max(abs(a_resp));
                x=linspace(-xx,xx,nbars);
                figure(fig_num);
                fig_num=fig_num+1;
                histogram(a_resp,x)
                ylabel(' Counts');
                if(iu==1 || iu==2)
                    xlabel('Accel (G)')
                else
                    xlabel('Accel (m/sec^2)')
                end
                title('Response Acceleration Histogram');
            
            end
            
            if(app.ResponseAccelPeakValueHistogramCheckBox.Value)
            
                figure(fig_num);
                fig_num=fig_num+1;
                xx=max(abs(pa));
                nbar=floor(nbars*(2/3));
                x=linspace(0,xx,nbar);
                histogram(pa,x)
                ylabel(' Counts');
                if(iu==1 || iu==2)
                    xlabel('Accel (G)')
                else
                    xlabel('Accel (m/sec^2)')
                end
                title('Absolute Acceleration Peak Value Distribution');
            
            end
            
            if(app.ResponseAccelEnvelopesCheckBox.Value)
            
                [envp,envn]=time_history_envelope(app.THM(:,1),a_resp,dt,pszcr);
            
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),a_resp,app.THM(:,1),envp,app.THM(:,1),envn);
                legend('Time History','Max Envelope','Min Envelope');
                out1=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            
            end
          
        end

        % Value changed function: listbox_unit
        function listbox_unitValueChanged(app, event)
            Clear(app);
        end

        % Value changing function: NaturalFrequencyHzEditField_2
        function NaturalFrequencyHzEditField_2ValueChanging(app, event)
            Clear(app);
        end

        % Value changing function: AmplificationFactorQEditField_2
        function AmplificationFactorQEditField_2ValueChanging(app, event)
            Clear(app);
        end

        % Callback function
        function pushbutton_statisticsButtonPushed(app, event)
            vibrationdata_statistics_Apph;
        end

        % Button pushed function: pushbutton_psd
        function pushbutton_psdButtonPushed(app, event)
            vibrationdata_psd_ah;
        end

        % Button pushed function: pushbutton_rainflow_fatigue
        function pushbutton_rainflow_fatigueButtonPushed(app, event)
            try
                rainflow_app;
            catch
            end
        end

        % Button pushed function: pushbutton_save
        function pushbutton_saveButtonPushed(app, event)
            
            Items = char(app.SelectOutputListBox.Items);
            im=app.SelectOutputListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.accel_data;
            end
            if(n==2)
                data=app.rel_disp_data;
            end
            
            try
                output_name=app.edit_output_array.Value;
                assignin('base', output_name, data);
                msgbox('Array saved');   
            catch
                warndlg('Saved failed');                
            end
            
            update(app);
        end

        % Value changed function: RelDispUnitListBox
        function RelDispUnitListBoxValueChanged(app, event)
            RelDispUnit(app);
            Clear(app);
        end

        % Button pushed function: pushbutton_calculate_2
        function pushbutton_calculate_2ButtonPushed(app, event)

            if(app.nMethod==1)
                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
            
            y=double(app.THM(:,2));

            n=app.nRDU;
                        
            figure(1);
            plot(app.THM(:,1),app.THM(:,2));
            title('Relative Displacement');
            xlabel('Time(sec)');
            grid on;
            if(n==1)
                ylabel('Rel Disp (in)');
                YS='Rel Disp (in)';
            else
                ylabel('Rel Disp (mm)');
                YS='Rel Disp (mm)';
            end
            
            scale=25.4;
            
            Z=str2num(app.Zlimitfor20millioncyclesEditField.Value);
            
            if(n==2)
                app.THM(:,2)=app.THM(:,2)/scale;
                Z=Z/scale;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            c=rainflow(app.THM(:,2));

            cycles=c(:,1);
            amp=c(:,2)/2;

            rd_Z_ratio = amp/Z;
            
            T=app.THM(end,1)-app.THM(1,1);
            
            num=length(amp);
            
            CDI=0;
            
            for i=1:num
            %
                if(rd_Z_ratio(i)>=6)
                    disp(' Failure:  relative displacement exceeds upper limit. ');
                    warndlg(' Failure:  relative displacement exceeds upper limit. ');
                    return
                end
            %
                N=10^(6.05-6.4*log10(rd_Z_ratio(i)));
            %
                d=(cycles(i)/N);    
                
                CDI=CDI+d;
            end
            
            EP=sum(cycles)/T;
            
            out44=sprintf(' Max amp = %8.4g \n',max(amp));
            disp(out44);
            
            out00=sprintf(' Max rd_Z_ratio = %8.4g \n\n',max(rd_Z_ratio));
            disp(out00);
            
            out11=sprintf(' Duration = %g sec   Cycles=%9.5g \n',T,T*EP);
            disp(out11);
            
            out22=sprintf('CDI = %8.4g \n\n',CDI);
            disp(out22);
            
            rate=CDI/T;
            
            out33=sprintf(' Damage Rate = %8.4g per sec\n\n',rate);
            disp(out33);

            ss=sprintf('%s %s %s %s \n',out22,out33,out00,out11);
            
            tfail=(0.7/rate);
            
            out2=sprintf('Time to failure = %8.4g sec   Cycles=%9.5g \n\n',tfail,tfail*EP);
            disp(out2);
            
            h=floor(tfail/3600);
            m=floor((tfail-h*3600)/60);
            s=floor( tfail-h*3600-m*60);
            
            string=sprintf('                 =  %g hr %g min %g sec \n',h,m,s);
            disp(string)

            ss=sprintf(' %s %s %s ',ss,out2,string);
            
            dsec=3600*24;
            ysec=dsec*365;
            
            y=floor(tfail/ysec);
            d=floor((tfail-y*ysec)/dsec);
            
            h=floor((tfail-y*ysec-d*dsec)/3600);
            m=floor((tfail-y*ysec-d*dsec-h*3600)/60);
            s=floor( tfail-y*ysec-d*dsec-h*3600-m*60);
            
            string=sprintf('                 =  %g years %g days %g hr %g min %g sec',y,d,h,m,s);
            disp(string)     

            ss=sprintf(' %s %s \n',ss,string);
            
            app.CumulativeDamageIndexTextArea.Value=ss;
            app.CumulativeDamageIndexTextArea.Visible='on';
            app.CumulativeDamageIndexTextAreaLabel.Visible='on';    
         
        end

        % Value changing function: Zlimitfor20millioncyclesEditField
        function Zlimitfor20millioncyclesEditFieldValueChanging(app, event)
            Clear(app);
        end

        % Button pushed function: pushbutton_psd_2
        function pushbutton_psd_2ButtonPushed(app, event)
            power_transmissibility_ah;
        end

        % Value changed function: NaturalFrequencyHzEditField_2
        function NaturalFrequencyHzEditField_2ValueChanged(app, event)
            value = app.NaturalFrequencyHzEditField_2.Value;
            
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
            Clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.sdofbaseUIFigure = uifigure('Visible', 'off');
            app.sdofbaseUIFigure.Name = 'sdof base';
            app.text9 = uilabel(app.sdofbaseUIFigure);
            app.text9.Tag = 'text9';
            app.text9.Text = 'vibrationdata_sdof_base_Apph    ver 3.7  by Tom Irvine    This script calculates the response of an SDOF system to base excitation.  ';
            app.text14 = uilabel(app.sdofbaseUIFigure);
            app.text14.Tag = 'text14';
            app.text14.Text = 'The input data must have two columns:  time(sec) & base acceleration         The numerical engine is the Smallwood ramp invariant digital recursive filtering relationship.';
            app.TabGroup = uitabgroup(app.sdofbaseUIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.listbox_unit = uilistbox(app.CalculateTab);
            app.listbox_unit.Items = {'G, in/sec, in', 'G,m/sec, mm', 'm/sec^2, m/sec, mm'};
            app.listbox_unit.ValueChangedFcn = createCallbackFcn(app, @listbox_unitValueChanged, true);
            app.listbox_unit.Tag = 'listbox_unit';
            app.listbox_unit.Value = 'G, in/sec, in';
            app.text10 = uilabel(app.CalculateTab);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Select Amplitude Units';
            app.Image = uiimage(app.CalculateTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.NaturalFrequencyHzEditField_2Label = uilabel(app.CalculateTab);
            app.NaturalFrequencyHzEditField_2Label.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField_2 = uieditfield(app.CalculateTab, 'text');
            app.NaturalFrequencyHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_2ValueChanged, true);
            app.NaturalFrequencyHzEditField_2.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditField_2ValueChanging, true);
            app.AmplificationFactorQEditField_2Label = uilabel(app.CalculateTab);
            app.AmplificationFactorQEditField_2Label.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_2 = uieditfield(app.CalculateTab, 'text');
            app.AmplificationFactorQEditField_2.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditField_2ValueChanging, true);
            app.AmplificationFactorQEditField_2.Value = '10';
            app.ResultsTextAreaLabel = uilabel(app.CalculateTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.CalculateTab);
            app.pushbutton_calculate = uibutton(app.CalculateTab, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculateButtonPushed, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.PlotTypesTab = uitab(app.TabGroup);
            app.PlotTypesTab.Title = 'Plot Types';
            app.BaseInputCheckBox = uicheckbox(app.PlotTypesTab);
            app.BaseInputCheckBox.Text = 'Base Input';
            app.AccelResponseCheckBox = uicheckbox(app.PlotTypesTab);
            app.AccelResponseCheckBox.Text = 'Accel Response';
            app.BothTimeHistoriesCheckBox = uicheckbox(app.PlotTypesTab);
            app.BothTimeHistoriesCheckBox.Text = 'Both Time Histories';
            app.BothTimeHistoriesCheckBox.Value = true;
            app.BothTimeHistoriesHistogramsCheckBox = uicheckbox(app.PlotTypesTab);
            app.BothTimeHistoriesHistogramsCheckBox.Text = 'Both Time Histories & Histograms';
            app.BothTimeHistoriesHistogramsCheckBox.Value = true;
            app.RelativeDisplacementCheckBox = uicheckbox(app.PlotTypesTab);
            app.RelativeDisplacementCheckBox.Text = 'Relative Displacement';
            app.ResponseAccelInstantaneousHistogramCheckBox = uicheckbox(app.PlotTypesTab);
            app.ResponseAccelInstantaneousHistogramCheckBox.Text = 'Response Accel Instantaneous  Histogram';
            app.ResponseAccelPeakValueHistogramCheckBox = uicheckbox(app.PlotTypesTab);
            app.ResponseAccelPeakValueHistogramCheckBox.Text = 'Response Accel Peak Value Histogram';
            app.ResponseAccelPeakValueHistogramCheckBox.Value = true;
            app.ResponseAccelEnvelopesCheckBox = uicheckbox(app.PlotTypesTab);
            app.ResponseAccelEnvelopesCheckBox.Text = 'Response Accel Envelopes';
            app.ExportPostprocessTab = uitab(app.TabGroup);
            app.ExportPostprocessTab.Title = 'Export & Post-process';
            app.TabGroup2 = uitabgroup(app.ExportPostprocessTab);
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.pushbutton_save = uibutton(app.ExportTab, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveButtonPushed, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.edit_output_array = uieditfield(app.ExportTab, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            app.edit_output_array.Value = 'response';
            app.text7 = uilabel(app.ExportTab);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Output Array Name';
            app.SelectOutputListBoxLabel = uilabel(app.ExportTab);
            app.SelectOutputListBoxLabel.Text = 'Select Output';
            app.SelectOutputListBox = uilistbox(app.ExportTab);
            app.SelectOutputListBox.Items = {'Absolute Acceleration', 'Relative Displacement'};
            app.SelectOutputListBox.Value = 'Absolute Acceleration';
            app.PSDTransTab = uitab(app.TabGroup2);
            app.PSDTransTab.Title = 'PSD & Trans';
            app.pushbutton_psd = uibutton(app.PSDTransTab, 'push');
            app.pushbutton_psd.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_psdButtonPushed, true);
            app.pushbutton_psd.Tag = 'pushbutton_psd';
            app.pushbutton_psd.Text = 'PSD';
            app.pushbutton_psd_2 = uibutton(app.PSDTransTab, 'push');
            app.pushbutton_psd_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_psd_2ButtonPushed, true);
            app.pushbutton_psd_2.Tag = 'pushbutton_psd';
            app.pushbutton_psd_2.Text = 'Power Transmissibility';
            app.RainflowFatigueTab = uitab(app.TabGroup2);
            app.RainflowFatigueTab.Title = 'Rainflow Fatigue';
            app.pushbutton_rainflow_fatigue = uibutton(app.RainflowFatigueTab, 'push');
            app.pushbutton_rainflow_fatigue.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_rainflow_fatigueButtonPushed, true);
            app.pushbutton_rainflow_fatigue.Tag = 'pushbutton_rainflow_fatigue';
            app.pushbutton_rainflow_fatigue.Text = 'Rainflow Fatigue';
            app.SteinbergFatigueTab = uitab(app.TabGroup2);
            app.SteinbergFatigueTab.Title = 'Steinberg Fatigue';
            app.RainflowcyclecountingperASTME1049852005Label = uilabel(app.SteinbergFatigueTab);
            app.RainflowcyclecountingperASTME1049852005Label.Text = 'Rainflow cycle counting per ASTM E 1049-85 (2005).   The input array must have two columns: time(sec) & rel disp';
            app.Notethattheamplitudeintheresultingtableispeakvalley2Label = uilabel(app.SteinbergFatigueTab);
            app.Notethattheamplitudeintheresultingtableispeakvalley2Label.Text = 'Note that the amplitude in the resulting table is:  (peak-valley)/2';
            app.SelectrelativedisplacementinputarrayfromleftlistboxLabel = uilabel(app.SteinbergFatigueTab);
            app.SelectrelativedisplacementinputarrayfromleftlistboxLabel.Text = 'Select relative displacement input array from left listbox.';
            app.pushbutton_calculate_2 = uibutton(app.SteinbergFatigueTab, 'push');
            app.pushbutton_calculate_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_2ButtonPushed, true);
            app.pushbutton_calculate_2.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate_2.Text = 'Calculate';
            app.RelDispUnitListBoxLabel = uilabel(app.SteinbergFatigueTab);
            app.RelDispUnitListBoxLabel.Text = 'Rel Disp Unit';
            app.RelDispUnitListBox = uilistbox(app.SteinbergFatigueTab);
            app.RelDispUnitListBox.Items = {'inch', 'mm'};
            app.RelDispUnitListBox.ValueChangedFcn = createCallbackFcn(app, @RelDispUnitListBoxValueChanged, true);
            app.RelDispUnitListBox.Value = 'inch';
            app.Zlimitfor20millioncyclesEditFieldLabel = uilabel(app.SteinbergFatigueTab);
            app.Zlimitfor20millioncyclesEditFieldLabel.Text = 'Z limit for 20 million cycles';
            app.Zlimitfor20millioncyclesEditField = uieditfield(app.SteinbergFatigueTab, 'text');
            app.Zlimitfor20millioncyclesEditField.ValueChangingFcn = createCallbackFcn(app, @Zlimitfor20millioncyclesEditFieldValueChanging, true);
            app.inch3sigmaLabel = uilabel(app.SteinbergFatigueTab);
            app.inch3sigmaLabel.Text = 'inch (3-sigma)';
            app.CumulativeDamageIndexTextAreaLabel = uilabel(app.SteinbergFatigueTab);
            app.CumulativeDamageIndexTextAreaLabel.Text = 'Cumulative Damage Index';
            app.CumulativeDamageIndexTextArea = uitextarea(app.SteinbergFatigueTab);
            app.pushbutton_return = uibutton(app.sdofbaseUIFigure, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_returnButtonPushed, true);
            app.pushbutton_return.Tag = 'pushbutton_calculate';
            app.pushbutton_return.Text = 'Return';
            app.text1 = uilabel(app.sdofbaseUIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.sdofbaseUIFigure);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.SelectInputArrayListBoxLabel = uilabel(app.sdofbaseUIFigure);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.sdofbaseUIFigure);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.UpdateListButton = uibutton(app.sdofbaseUIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            % Show the figure after all components are created
            app.sdofbaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_sdof_base_Apph

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.sdofbaseUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.sdofbaseUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sdofbaseUIFigure)
        end
    end
end