classdef vibrationdata_sdof_base_Apph2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uipanel4                        matlab.ui.container.Panel
        ResponseAccelEnvelopesCheckBox  matlab.ui.control.CheckBox
        ResponseAccelPeakValueHistogramCheckBox  matlab.ui.control.CheckBox
        ResponseAccelInstantaneousHistogramCheckBox  matlab.ui.control.CheckBox
        RelativeDisplacementCheckBox    matlab.ui.control.CheckBox
        BothTimeHistoriesHistogramsCheckBox  matlab.ui.control.CheckBox
        BothTimeHistoriesCheckBox       matlab.ui.control.CheckBox
        AccelResponseCheckBox           matlab.ui.control.CheckBox
        BaseInputCheckBox               matlab.ui.control.CheckBox
        uipanel2                        matlab.ui.container.ButtonGroup
        pushbutton_Steinberg            matlab.ui.control.Button
        pushbutton_psd                  matlab.ui.control.Button
        pushbutton_statistics           matlab.ui.control.Button
        pushbutton_rainflow_fatigue     matlab.ui.control.Button
        listbox_output_type             matlab.ui.control.ListBox
        text13                          matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        edit_output_array               matlab.ui.control.EditField
        pushbutton_save                 matlab.ui.control.Button
        pushbutton_calculate            matlab.ui.control.Button
        pushbutton_return               matlab.ui.control.Button
        text16                          matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        edit_results                    matlab.ui.control.TextArea
        text14                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        uipanel1                        matlab.ui.container.Panel
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorQLabel       matlab.ui.control.Label
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzLabel         matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        Image                           matlab.ui.control.Image
        text10                          matlab.ui.control.Label
        listbox_unit                    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        THM % Description
    end
    
    methods (Access = private)

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end           
        end
        
        function update(app)
            cell_array=update_time_history();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_sdof_base_OpeningFcn(app, varargin)
           
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_sdof_base (see VARARGIN)
            
            % Choose default command line output for vibrationdata_sdof_base
            handles.output = hObject;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            ClosePlots(app);

            set(handles.pushbutton_save,'Enable','off');
            set(handles.edit_output_array,'Enable','off');
            
            set(handles.edit_results,'Enable','off');
            
            set(handles.pushbutton_rainflow_fatigue,'Enable','off');
            set(handles.pushbutton_Steinberg,'Enable','off');
            
            
            set(handles.pushbutton_statistics,'Enable','off');
            set(handles.pushbutton_psd,'Enable','off');

            update(app);

            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function edit_Q_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Q (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Q as text
            %        str2double(get(hObject,'String')) returns contents of edit_Q as a double
            set(handles.edit_results,'Enable','off');
            set(handles.edit_results,'String',' ');
        end

        % Callback function
        function edit_fn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_fn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_fn as text
            %        str2double(get(hObject,'String')) returns contents of edit_fn as a double
            set(handles.edit_results,'Enable','off');
            set(handles.edit_results,'String',' ');
        end

        % Value changed function: listbox_unit
        function listbox_unit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_unit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_unit contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_unit
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end

        % Button pushed function: pushbutton_Steinberg
        function pushbutton_Steinberg_Callback(app, event)

        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ClosePlots(app);
            
            disp(' ');
            disp(' * * * * * ');
            disp(' ');
            
            set(handles.edit_results,'Enable','on');
            
            iu=get(handles.listbox_unit,'Value');
            

            k=get(handles.listbox_method,'Value');
            
            
            if(k==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            sz=size(app.THM);
            
            if(sz(2)~=2)
               warndlg('Input array must have two columns');
               return;
            end
            
            [app.THM,iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(iflag==999)
                return;
            end
            
            y=double(app.THM(:,2));
            yy=y;
            
            dt=mean(diff(app.THM(:,1)));
   
            Q=str2num(app.AmplificationFactorQEditField.Value);
            
            fn=str2num(app.NaturalFrequencyHzEditField.Value);
            
            if(isempty(fn)==1)
                warndlg('Enter natural frequency');
                return;
            end

            damp=1/(2*Q);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Initialize coefficients
            %
            [a1,a2,b1,b2,b3,rd_a1,rd_a2,rd_b1,rd_b2,rd_b3]=...
                                               srs_coefficients(fn,damp,dt);
            %
            %
            %  SRS engine
            %
            
            a_pos=zeros(1,1);
            a_neg=zeros(1,1);
            rd_pos=zeros(1,1);
            rd_neg=zeros(1,1);
            
            for j=1:1
            %
                disp(' ')
                disp(' Calculating absolute acceleration');
            %
                [a_resp,a_pos(j),a_neg(j)]=arbit_engine(a1(j),a2(j),b1(j),b2(j),b3(j),yy);
            
                zdd=a_resp-y;
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
                disp(' ')
                disp(' Calculating relative displacement');
            %
                [rd_resp,rd_pos(j),rd_neg(j)]=arbit_engine(rd_a1(j),rd_a2(j),rd_b1(j),rd_b2(j),rd_b3(j),yy);
            
                z=rd_resp;
            
                omegan=2*pi*fn;
                tdo=2*damp*omegan;
            %
                om2=omegan^2;
            
                zd=(-yy-zdd-om2*z)/tdo;
            
                EK=0;
                ED=0;
                EA=0;
            
                for i=1:length(z)
                    EK=EK+zdd(i)*zd(i);
                    ED=ED+tdo*zd(i)^2;
                    EA=EA+om2*z(i)*zd(i);
                end
            %
                EK=EK*dt;
                ED=ED*dt;
                EA=EA*dt;
            %
            %
                if(iu==1)
                    scale=386;
                end
                if(iu==2)
                    scale=9.81*1000;
                end
                if(iu==3)
                    scale=1000;
                end
            %
                rd_resp=rd_resp*scale;
                rd_pos(j)=rd_pos(j)*scale;
                rd_neg(j)=rd_neg(j)*scale;
            
                if(iu==1)
                    escale=386^2;
                    slabel='(in/sec)^2';
                end
                if(iu==2)
                    escale=9.81^2;
                    slabel='(m/sec)^2';
                end
                if(iu==3)
                    escale=1;
                    slabel='(m/sec)^2';
                end
            
                EK=EK*escale;
                ED=ED*escale;
                EA=EA*escale;
            %
                EI=EK+ED+EA;
            
                EK=abs(EK);
                ED=abs(ED);
                EA=abs(EA);
                EI=abs(EI);
            
                ss1='Kinetic Energy = ';
                ss2='Dissipated Energy = ';
                ss3='Absorbed Energy = ';
                ss4='Total Energy = ';
            
            end
            %
            om=2*pi*fn;
            om2=om^2;
            %
            zd=(-yy-zdd-om2*z)/(2*damp*om);
            %
            [~,a_sd,a_rms,sk,kt]=kurtosis_stats(a_resp);
            [~,~,rd_rms,~,~]=kurtosis_stats(rd_resp);
            
            %
            if(iu==1 || iu==2)
                big_string=sprintf(' Acceleration Response (G) \n');
            else
                big_string=sprintf(' Acceleration Response (m/sec^2) \n');
            end
            
            crest=(max([a_pos(1) abs(a_neg(1))]))/a_sd;
            
            dur=app.THM(end,1)-app.THM(1,1);
            
            [pszcr,peak_rate,~,pa]=zero_crossing_function_alt(app.THM(:,1),a_resp,dur);
            [rf]=Rice_frequency(a_resp,dt);
            
  
            string1=sprintf('\n\n max=%8.4g',a_pos(1));
            string2=sprintf('\n min=%8.4g',-a_neg(1));
            string3=sprintf('\n\n RMS=%8.4g',a_rms);
            string4=sprintf('\n std dev=%8.4g',std(a_resp));
            string5=sprintf('\n mean=%8.4g',mean(a_resp));
            string6=sprintf('\n\n crest factor=%7.3g',crest);
            string7=sprintf('\n skewness=%7.3g',sk);
            string8=sprintf('\n kurtosis=%7.3g',kt);
            
            string_rf=sprintf('\n\n Rice Characteristic Frequency = %8.4g Hz ',rf);
            string_zc=sprintf('\n Positive Slope Zero Cross Rate = %8.4g Hz ',pszcr);
            string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            
            big_string=strcat(big_string,string1);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            %
            
            
            if(iu==1)
                string8=sprintf('\n\n Relative Displacement (in) \n');
            else
                string8=sprintf('\n\n Relative Displacement (mm) \n');
            end
            
            string9=sprintf('\n\n max=%7.3g',rd_pos(1));
            string10=sprintf('\n min=%7.3g',rd_neg(1));
            string11=sprintf('\n RMS=%7.3g',rd_rms);
            
            
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            big_string=strcat(big_string,string10);
            big_string=strcat(big_string,string11);
            
            
            big_string=strcat(big_string,string_rf);
            big_string=strcat(big_string,string_zc);
            big_string=strcat(big_string,string_pr);
            
            ss1='Kinetic Energy/Mass = ';
            ss2='Dissipated Energy/Mass = ';
            ss3='Absorbed Energy/Mass = ';
            ss4='Total Energy/Mass = ';
            
            sss=sprintf('\n\n %s %8.4g %s \n %s %8.4g %s \n %s %8.4g %s \n %s %8.4g %s',ss1,EK,slabel,ss2,ED,slabel,ss3,EA,slabel,ss4,EI,slabel);
            
            big_string=strcat(big_string,sss);
            
            
            out12=sprintf('\n Q=%g ',Q);
            disp(out12);
            
            
            spa=pa/a_sd;
            npa=length(pa);
            
              L3=length(spa(spa > 3.0));
            L3p5=length(spa(spa > 3.5));
              L4=length(spa(spa > 4.0));
            L4p5=length(spa(spa > 4.5));
              L5=length(spa(spa > 5.0));
            L5p5=length(spa(spa > 5.5));
              L6=length(spa(spa > 6.0));
            
            disp(' ');
            disp(' Number of Peaks >= n-sigma');
            disp(' ');
            
            out1=sprintf(' 3.0-sigma    %d  or %7.3g percent',L3,100*L3/npa);
            out2=sprintf(' 3.5-sigma    %d  or %7.3g percent',L3p5,100*L3p5/npa);
            out3=sprintf(' 4.0-sigma    %d  or %7.3g percent',L4,100*L4/npa);
            out4=sprintf(' 4.5-sigma    %d  or %7.3g percent',L4p5,100*L4p5/npa);
            out5=sprintf(' 5.0-sigma    %d  or %7.3g percent',L5,100*L5/npa);
            out6=sprintf(' 5.5-sigma    %d  or %7.3g percent',L5p5,100*L5p5/npa);
            out7=sprintf(' 6.0-sigma    %d  or %7.3g percent',L6,100*L6/npa);
            
            disp(out1);
            disp(out2);
            disp(out3);
            disp(out4);
            disp(out5);
            disp(out6);
            disp(out7);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            disp(' ');
            disp(' Acceleration Input & Response Statistics ');
            disp(' ');
            
            [rho]=Pearson_coefficient(yy,a_resp);
            
            out1=sprintf(' Pearson product-moment correlation coefficient = %8.4g ',rho);
            disp(out1);

            sss=sprintf('\n\n Pearson product-moment \n correlation coefficient = %8.4g ',rho);
            
            big_string=strcat(big_string,sss);
            set(handles.edit_results,'String',big_string);
            disp(' ');
            disp(big_string);
            
            disp(' ');
            disp(' Covariance Matrix: ');
            disp(' ');
            
            cvm = cov(yy,a_resp)
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            accel_data=[app.THM(:,1) a_resp];
            
            rel_disp_data=[app.THM(:,1) rd_resp];
            %
            fig_num=1;
            %
            data1=app.THM;
            data2=[app.THM(:,1),a_resp];
            xlabel2=' Time(sec) ';
            nbars=31;
            
            figure(fig_num)
            fig_num=fig_num+1;
            plot(app.THM(:,1),app.THM(:,2),app.THM(:,1),a_resp);
            xlabel(' Time(sec) ');
            legend('Base Input','Response');
            if(iu==1 || iu==2)
                ylabel('Accel (G)')
            else
                ylabel('Accel (m/sec^2)')
            end
            grid on;
            title('Acceleration Time History');
            
            if(app.BaseInputCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),app.THM(:,2));
            
                title('Base Input Acceleration Time History');
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            end
            
            if(app.AccelResponseCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),a_resp);
                out1=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.BothTimeHistoriesCheckBox.Value)
            
                if(iu==1 || iu==2)
                    ylabel1='Accel (G)';
                    ylabel2='Accel (G)';
                else
                    ylabel1='Accel (m/sec^2)';
                    ylabel2='Accel (m/sec^2)';
                end
            
                t_string1=('Base Input');
                t_string2=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
            
                [fig_num]=...
                subplots_two_linlin_two_titles(fig_num,xlabel2,ylabel1,ylabel2,...
                                                      data1,data2,t_string1,t_string2);
            
            end
            
            if(app.BothTimeHistoriesHistogramsCheckBox.Value)
                nbars=31;
            
                [fig_num]=plot_two_time_histories_histograms(fig_num,xlabel2,...
                                ylabel1,ylabel2,data1,data2,t_string1,t_string2,nbars);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.RelativeDisplacementCheckBox.Value)
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),rd_resp);
                out1=sprintf('Relative Displacement fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1)
                    ylabel('Rel Disp (in)')
                else
                    ylabel('Rel Disp (mm)')
                end
                grid on;
            
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.ResponseAccelInstantaneousHistogramCheckBox.Value)
            
                xx=max(abs(a_resp));
                x=linspace(-xx,xx,nbars);
                figure(fig_num);
                fig_num=fig_num+1;
                histogram(a_resp,x)
                ylabel(' Counts');
                if(iu==1 || iu==2)
                    xlabel('Accel (G)')
                else
                    xlabel('Accel (m/sec^2)')
                end
                title('Response Acceleration Histogram');
            
            end
            
            if(app.ResponseAccelPeakValueHistogramCheckBox.Value)
            
                figure(fig_num);
                fig_num=fig_num+1;
                xx=max(abs(pa));
                nbar=floor(nbars*(2/3));
                x=linspace(0,xx,nbar);
                histogram(pa,x)
                ylabel(' Counts');
                if(iu==1 || iu==2)
                    xlabel('Accel (G)')
                else
                    xlabel('Accel (m/sec^2)')
                end
                title('Absolute Acceleration Peak Value Distribution');
            
            end
            
            if(app.ResponseAccelEnvelopesCheckBox.Value)
            
                [envp,envn]=time_history_envelope(app.THM(:,1),a_resp,dt,pszcr);
            
                figure(fig_num);
                fig_num=fig_num+1;
                plot(app.THM(:,1),a_resp,app.THM(:,1),envp,app.THM(:,1),envn);
                legend('Time History','Max Envelope','Min Envelope');
                out1=sprintf('Acceleration Response fn=%g Hz  Q=%g',fn,Q);
                title(out1);
                xlabel(' Time(sec) ')
                if(iu==1 || iu==2)
                    ylabel('Accel (G)')
                else
                    ylabel('Accel (m/sec^2)')
                end
                grid on;
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            setappdata(0,'accel_data',accel_data);
            setappdata(0,'rel_disp_data',rel_disp_data);
            
            set(handles.pushbutton_save,'Enable','on');
            set(handles.edit_output_array,'Enable','on');
        end

        % Button pushed function: pushbutton_psd
        function pushbutton_psd_Callback(app, event)
            vibrationdata_psd_ah;
        end

        % Button pushed function: pushbutton_rainflow_fatigue
        function pushbutton_rainflow_fatigue_Callback(app, event)
            try
                rainflow_app;
            catch
            end
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_output_type,'Value');
            
            if(n==1)
                data=getappdata(0,'accel_data');
            end
            if(n==2)
                data=getappdata(0,'rel_disp_data');
            end
            
            output_name=get(handles.edit_output_array,'String');
            assignin('base', output_name, data);
            
            set(handles.pushbutton_rainflow_fatigue,'Enable','on');
            set(handles.pushbutton_Steinberg,'Enable','on');
            
            set(handles.pushbutton_statistics,'Enable','on');
            set(handles.pushbutton_psd,'Enable','on');

            h = msgbox('Save Complete');
        end

        % Button pushed function: pushbutton_statistics
        function pushbutton_statistics_Callback(app, event)

            vibrationdata_statistics_Apph;
 
        end

        % Callback function
        function edit_fnValueChanging(app, event)
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end

        % Callback function
        function edit_QValueChanging(app, event)
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end

        % Callback function
        function edit_input_arrayValueChanging(app, event)
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end

        % Value changed function: SelectInputArrayListBox
        function SelectInputArrayListBoxValueChanged(app, event)
          
            
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            % hObject    handle to listbox_method (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_method contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_method
            n=get(hObject,'Value');
            
            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   setappdata(0,'app.THM',app.THM);
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
            
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            app.edit_results.Enable='off';
            app.edit_results.Value=' '; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_sdof_base';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Enter Data';
            app.uipanel1.Tag = 'uipanel1';
            app.listbox_unit = uilistbox(app.uipanel1);
            app.listbox_unit.Items = {'G, in/sec, in', 'G,m/sec, mm', 'm/sec^2, m/sec, mm'};
            app.listbox_unit.ValueChangedFcn = createCallbackFcn(app, @listbox_unit_Callback, true);
            app.listbox_unit.Tag = 'listbox_unit';
            app.listbox_unit.Value = 'G, in/sec, in';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Select Amplitude Units';
            app.Image = uiimage(app.uipanel1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.listbox_method = uilistbox(app.uipanel1);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.SelectInputArrayListBoxLabel = uilabel(app.uipanel1);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.uipanel1);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.SelectInputArrayListBox.ValueChangedFcn = createCallbackFcn(app, @SelectInputArrayListBoxValueChanged, true);
            app.UpdateListButton = uibutton(app.uipanel1, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.NaturalFrequencyHzLabel = uilabel(app.uipanel1);
            app.NaturalFrequencyHzLabel.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField = uieditfield(app.uipanel1, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.AmplificationFactorQLabel = uilabel(app.uipanel1);
            app.AmplificationFactorQLabel.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField = uieditfield(app.uipanel1, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'vibrationdata_sdof_base_Apph    ver 3.4  by Tom Irvine    This script calculates the response of an SDOF system to base excitation.  ';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'The input data must have two columns:  time(sec) & base acceleration';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Results';
            app.uipanel3.Tag = 'uipanel3';
            app.edit_results = uitextarea(app.uipanel3);
            app.edit_results.Tag = 'edit_results';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'The numerical engine is the Smallwood ramp invariant digital recursive filtering relationship.';
            app.pushbutton_return = uibutton(app.figure1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.pushbutton_calculate = uibutton(app.figure1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = ' Save Output Time History to Matlab Workspace';
            app.uipanel2.Tag = 'uipanel2';
            app.pushbutton_save = uibutton(app.uipanel2, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.edit_output_array = uieditfield(app.uipanel2, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            app.edit_output_array.Value = 'response';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Output Array Name';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Select Output';
            app.listbox_output_type = uilistbox(app.uipanel2);
            app.listbox_output_type.Items = {'Acceleration Response', 'Relative Displacement'};
            app.listbox_output_type.Tag = 'listbox_output_type';
            app.listbox_output_type.Value = 'Acceleration Response';
            app.pushbutton_rainflow_fatigue = uibutton(app.uipanel2, 'push');
            app.pushbutton_rainflow_fatigue.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_rainflow_fatigue_Callback, true);
            app.pushbutton_rainflow_fatigue.Tag = 'pushbutton_rainflow_fatigue';
            app.pushbutton_rainflow_fatigue.Text = 'Rainflow Fatigue';
            app.pushbutton_statistics = uibutton(app.uipanel2, 'push');
            app.pushbutton_statistics.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_statistics_Callback, true);
            app.pushbutton_statistics.Tag = 'pushbutton_statistics';
            app.pushbutton_statistics.Text = 'Statistics';
            app.pushbutton_psd = uibutton(app.uipanel2, 'push');
            app.pushbutton_psd.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_psd_Callback, true);
            app.pushbutton_psd.Tag = 'pushbutton_psd';
            app.pushbutton_psd.Text = 'PSD';
            app.pushbutton_Steinberg = uibutton(app.uipanel2, 'push');
            app.pushbutton_Steinberg.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Steinberg_Callback, true);
            app.pushbutton_Steinberg.Tag = 'pushbutton_Steinberg';
            app.pushbutton_Steinberg.Text = 'Steinberg Fatigue';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Output Plots';
            app.uipanel4.Tag = 'uipanel4';
            app.BaseInputCheckBox = uicheckbox(app.uipanel4);
            app.BaseInputCheckBox.Text = 'Base Input';
            app.AccelResponseCheckBox = uicheckbox(app.uipanel4);
            app.AccelResponseCheckBox.Text = 'Accel Response';
            app.BothTimeHistoriesCheckBox = uicheckbox(app.uipanel4);
            app.BothTimeHistoriesCheckBox.Text = 'Both Time Histories';
            app.BothTimeHistoriesCheckBox.Value = true;
            app.BothTimeHistoriesHistogramsCheckBox = uicheckbox(app.uipanel4);
            app.BothTimeHistoriesHistogramsCheckBox.Text = 'Both Time Histories & Histograms';
            app.BothTimeHistoriesHistogramsCheckBox.Value = true;
            app.RelativeDisplacementCheckBox = uicheckbox(app.uipanel4);
            app.RelativeDisplacementCheckBox.Text = 'Relative Displacement';
            app.ResponseAccelInstantaneousHistogramCheckBox = uicheckbox(app.uipanel4);
            app.ResponseAccelInstantaneousHistogramCheckBox.Text = 'Response Accel Instantaneous  Histogram';
            app.ResponseAccelPeakValueHistogramCheckBox = uicheckbox(app.uipanel4);
            app.ResponseAccelPeakValueHistogramCheckBox.Text = 'Response Accel Peak Value Histogram';
            app.ResponseAccelEnvelopesCheckBox = uicheckbox(app.uipanel4);
            app.ResponseAccelEnvelopesCheckBox.Text = 'Response Accel Envelopes';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_sdof_base_Apph2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_sdof_base_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end