classdef vibrationdata_spl_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIvibrationdatasplUIFigure     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SPLTab                         matlab.ui.container.Tab
        ReplotNewdBLimitsButton        matlab.ui.control.Button
        MeanRemovalListBox             matlab.ui.control.ListBox
        MeanRemovalListBoxLabel        matlab.ui.control.Label
        WindowListBox                  matlab.ui.control.ListBox
        WindowListBoxLabel             matlab.ui.control.Label
        OctaveBandListBox              matlab.ui.control.ListBox
        OctaveBandListBoxLabel         matlab.ui.control.Label
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        OutputSPLArrayNameEditField    matlab.ui.control.EditField
        OutputSPLArrayNameEditFieldLabel  matlab.ui.control.Label
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        ApplyWeightingNetworkListBox   matlab.ui.control.ListBox
        ApplyWeightingNetworkListBoxLabel  matlab.ui.control.Label
        ProcessingListBox              matlab.ui.control.ListBox
        ProcessingListBoxLabel         matlab.ui.control.Label
        InputPressureUnitListBox       matlab.ui.control.ListBox
        InputPressureUnitListBoxLabel  matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        CalculateSPLButton             matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        PNLTab                         matlab.ui.container.Tab
        CalculatePNLButton             matlab.ui.control.Button
        InputSPLArrayNameEditField     matlab.ui.control.EditField
        InputSPLArrayNameEditFieldLabel  matlab.ui.control.Label
        TheinputfilemusthavetwocolumnsfcHzSPLdBref20microPaLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        vibrationdata_spl_aver17byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        SPL    %
        THM    %
        iflag  %
        tstart %
        tend   %
        a      %
        fmin   % 
        fmax   %
        pf     %
        pspl   %  
        oaspl  %
        scale_label %
        fig_num %
        iwn     %
        noct    %
    end
    
    methods (Access = private)

        function update(app)
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
        end
        
        function ClosePlots(app) 
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                
        end
        
        function PreProcess(app)
        
            ClosePlots(app);
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end
                     
            ns=app.ProcessingListBox.Value;
            if(contains(ns,'Segment'))
                app.tstart=app.StartTimesecEditField.Value;
                app.tend=app.EndTimesecEditField.Value;   
                [~,istart]=min(abs(app.tstart-app.THM(:,1)));      
                  [~,iend]=min(abs(app.tend-app.THM(:,1)));      
                   
                app.THM=app.THM(istart:iend,:);                   
            end
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);

        end

        function PlotSPL(app)
            
            %
                minp=min(app.pspl);
                maxp=max(app.pspl);
            %
                for i=0:20
                    b=i*10;
                    if(b>minp)
                        ymin=b-10;
                        break;
                    end    
                end
            %
                for i=0:20
                    b=i*10;
                    if(b>maxp)
                        ymax=b;
                        break;
                    end    
                end
            %
            
            figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.pf,app.pspl);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %
                if(app.noct==1)
                    if(app.iwn==1)
                        out7=sprintf('One-Third Octave SPL   OASPL=%7.4g dB  Ref= 20 micro Pa',app.oaspl);
                    else
                        out7=sprintf('One-Third Octave SPL %s-scale  OASPL=%7.4g dB  Ref= 20 micro Pa',app.scale_label,app.oaspl);        
                    end
                else
                    if(app.iwn==1)
                        out7=sprintf('One Octave SPL   OASPL=%7.4g dB  Ref= 20 micro Pa',app.oaspl);
                    else
                        out7=sprintf('One Octave SPL %s-scale  OASPL=%7.4g dB  Ref= 20 micro Pa',app.scale_label,app.oaspl);        
                    end                    
                end
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
                    
                [xtt,xTT,app.iflag]=xtick_label(app.fmin,app.fmax);
            
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    app.fmin=min(xtt);
                    app.fmax=max(xtt);
                    xlim([app.fmin,app.fmax]);    
                end   
                try
                    ylim([ymin,ymax]);
                catch
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
            %
              app.pf=fix_size(app.pf);
              app.pspl=fix_size(app.pspl);
              app.SPL=[app.pf app.pspl];
            %
                clear f;
            %
                bn=zeros(length(app.pspl),1);
                
                for i=1:length(app.pspl)
                    bn(i)=i;
                    f{i}=' ';
                    
                    for j=1:15
                            
                        if( (abs(app.pf(i)-app.a(j))/app.a(j)) < 0.05 )
                                            
                            if(j==11)
                                f{i}='1K';
                                break;
                            end
                            if(j==12)
                                f{i}='2K';
                                break;
                            end
                            if(j==13)
                                f{i}='4K';
                                break;
                            end
                            if(j==14)
                                f{i}='8K';
                                break;
                            end
                            if(j==15)
                                f{i}='16K';
                                break;
                            end               
                            f{i}=sprintf('%g',app.a(j));
                            break;
                        end    
              
                    end    
                       
                end
            %
                f=fix_size(f);
            %
                capp.pf(1)=app.pf(1);
                
                if(app.noct==1)
                    oct=2^(1/3);
                else
                    oct=2;
                end    

                for i=2:length(app.pf)
                    capp.pf(i)=capp.pf(i-1)*oct;
                end
            %
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                xx=log10(capp.pf);
                width=0.7;
                bar(xx,app.pspl,width);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');
                set(gca,'Xtick',xx)
                set(gca,'XTickLabel',f)

                app.fmin=log10(min(app.pf)/1.2599);
                app.fmax=log10(max(app.pf)*1.2599);
            %
                xlim([app.fmin,app.fmax]);
                try
                    ylim([ymin,ymax]);
                catch
                end
            %  
        end    


        function Signal(app)
            value = app.ProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on';            
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Signal(app);
            update(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function ProcessingListBoxValueChanged(app, event)
            Signal(app);
        end

        % Callback function
        function CalculateButtonPushed(app, event)
        
        end

        % Callback function
        function CalculateButtonPushed2(app, event)
                      
        end

        % Button pushed function: CalculateSPLButton
        function CalculateSPLButtonPushed(app, event)
            PreProcess(app);

            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            if(iflagq~=1)
	            return
            end
            
            fprintf('\n * * * * * * * * \n\n');
            
            app.fig_num=1;             
            
            m=app.InputPressureUnitListBox.Value;
            
            if(strcmp(m,'psi'))
                p_unit=sprintf('psi');
                ref = 2.9e-09;
            end
            if(strcmp(m,'Pascal'))
                p_unit=sprintf('Pa'); 
                ref = 20.e-06;
            end
            if(contains(m,'micro'))
                p_unit=sprintf('micro Pa');
                ref = 20.;
            end
            
            %%%%%%%%            
            
            ntime=app.ProcessingListBox.Value;
            
            if(contains(ntime,'Entire'))
                t1=app.THM(1,1);
                t2=app.THM(end,1);
            else    
                t1=app.StartTimesecEditField.Value;
                t2=app.EndTimesecEditField.Value;   
            end
            
            figure(app.fig_num)
            app.fig_num=app.fig_num+1;
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            xlabel('Time (sec)');
            YS=sprintf('Sound Pressue (%s)',p_unit);
            ylabel(YS);

            mmr=app.MeanRemovalListBox.Value;
            
            if(strcmp(mmr,'Yes'))
                mr=1;
            else
                mr=2;
            end
            
            Items = strtrim(app.WindowListBox.Items);
            im = strtrim(app.WindowListBox.Value);
            window = find(strcmp(Items, im));

            
            [spl,app.oaspl,app.iflag]=spl_function_alt_tc(mr,window,t1,t2,app.THM,ref);
            
            if(app.iflag==999)
                warndlg('No output generated');
                return;
            end    
            
            app.pf=spl(:,1);
            app.pspl=spl(:,2);
            
            imax=length(app.pf);
            
            qapp.iwn=app.ApplyWeightingNetworkListBox.Value;
            
            if(contains(qapp.iwn,'No'))
                app.iwn=1;
            end
            if(contains(qapp.iwn,'A'))
                app.iwn=2;
            end        
            if(contains(qapp.iwn,'B'))
                app.iwn=3;
            end            
            if(contains(qapp.iwn,'C'))
                app.iwn=4;
            end
            
            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value;

            [~,ii]=min(abs(app.pf-app.fmin));
            [~,jj]=min(abs(app.pf-app.fmax));
            
            app.pf=app.pf(ii:jj);
            app.pspl=app.pspl(ii:jj);

            if(app.iwn>=2)
                [app.pf,app.pspl,app.oaspl,app.scale_label]=acoustic_weighting_function(app.pf,app.pspl,imax,ref,app.iwn);
            end 
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %       
            fprintf('\n app.oaspl = %8.4g dB  ref 20 micro Pa \n\n',app.oaspl);
            %
            if(app.iwn==1)
                p_rms = ref*(10^(app.oaspl/20.));
                fprintf('  pressure in air = %8.4g %s rms  \n\n',p_rms,p_unit);
            end
            %
            app.a(1)=1;
            app.a(2)=2;
            app.a(3)=4;
            app.a(4)=8; 
            app.a(5)=16;
            app.a(6)=31.5;
            app.a(7)=63;
            app.a(8)=125;
            app.a(9)=250;
            app.a(10)=500;
            app.a(11)=1000;
            app.a(12)=2000;
            app.a(13)=4000;
            app.a(14)=8000;
            app.a(15)=16000;
            app.a(16)=2*app.a(15);
            app.a(17)=2*app.a(16);
            app.a(18)=2*app.a(17);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    

            Items = char(app.OctaveBandListBox.Items);
            im=app.OctaveBandListBox.Value;
            [app.noct]=find_index(Items,im);

            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value;

            if(app.noct==2)

                ms=length(app.pf);
                na=length(app.a); 

                ff=fix_size(app.a);
                oo=zeros(na,1);

                flow=ff/sqrt(2);
                fhigh=ff*sqrt(2);

                for i=1:na
                    for j=1:ms
                        if(flow(i)<=app.pf(j) && app.pf(j)<=fhigh(i))
                            oo(i)=oo(i)+10^(app.pspl(j)/10);
                        end    
                    end
                end    

                for i=na:-1:1
                    if(oo(i)==0)
                        ff(i)=[];
                        oo(i)=[];
                    end    
                end    

                for i=1:length(ff)
                    oo(i)=10*log10(oo(i));
                end    

                [oadb]=oaspl_function(oo);

                delta=app.oaspl-oadb;

                oo=oo+delta;

                clear app.SPL;

                app.SPL=[ff oo];

                app.pf=ff;
                app.pspl=oo;

            end
            PlotSPL(app);

            try
                name=app.OutputSPLArrayNameEditField.Value;
                assignin('base',name, app.SPL);                
            catch
            end
            
        end

        % Button pushed function: CalculatePNLButton
        function CalculatePNLButtonPushed(app, event)
            disp(' ');
            disp(' * * * * ');
             
            FS=app.InputSPLArrayNameEditField.Value;
            
            try
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end               
            
             
            fc=app.THM(:,1);
            spl=app.THM(:,2);
            %
            [oadb]=app.oaspl_function(spl);
            %
            fprintf('\n  Input SPL Overall Level = %8.4g dB  re 20 micro Pa \n\n',oadb);
            %
            [nfc,noys]=noys_table();
             
            NL=length(nfc);
             
            nv=zeros(NL,1);
             
            [~,i1] = min(abs(fc-50));
            [~,i2] = min(abs(fc-10000));
             
            ffc=fc(i1:i2);  % keep this command
            ddB=spl(i1:i2);
             
            for i=1:NL
                
                w=nfc(i);  % keep this command
             
                [~,index] = min(abs(ffc-w));
               
                   
                nspl=round(ddB(index));
                
                if(nspl>150)
                    nspl=150;
                end
                    
                nv(i)=noys(nspl,index);
                    
            end
             
            nvsum=sum(nv);
             nmax=max(nv);
             
              N = 0.85*nmax + 0.15*nvsum;
            PNL = 40 + 10*log(N)/log(2);
             
            [PNLT]=PNL_tone_correction(NL,spl,PNL,nfc);
            %
            %%%%%%%%%%%%%%%%%%%%
             
             
            fprintf('                   Total Noisiness Index N = %7.4g \n',N);
             
            fprintf('                 Perceived Noise Level PNL = %7.4g PNdB \n',PNL);
      
            fprintf(' Tone Corrected Perceived Noise Level PNLT = %7.4g TPNdB \n',PNLT);
             
            %
            figure(1);
            %
            plot(app.THM(:,1),app.THM(:,2));
            set(gca,'MinorGridLineStyle',':','GridLineStyle',':','XScale','log','YScale','lin')
            grid on;
            out1=sprintf(' One-Third Octave Sound Pressure Level \n app.oaspl = %8.4g dB  Ref: 20 micro Pa ',oadb);
            title(out1)
            xlabel(' Center Frequency (Hz) ');
            ylabel(' SPL (dB) ');
             
            msgbox(' Results written to Command Window');

        end

        % Value changed function: ProcessingListBox
        function ProcessingListBoxValueChanged2(app, event)
            Signal(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    

        end

        % Button pushed function: ReplotNewdBLimitsButton
        function ReplotNewdBLimitsButtonPushed(app, event)
            
            app.fmin=app.MinEditField.Value;
            app.fmax=app.MaxEditField.Value;

            prompt = {'Max dB','Min dB'};
            dlgtitle = 'Input';
            fieldsize = [1 25; 1 25];
            definput = {'',''};
            YY = inputdlg(prompt,dlgtitle,fieldsize,definput);

            YY=str2num(char(YY));

            ymin=min(YY);
            ymax=max(YY);

            figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(app.pf,app.pspl);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %
                if(app.noct==1)
                    if(app.iwn==1)
                        out7=sprintf('One-Third Octave SPL   OASPL=%7.4g dB  Ref= 20 micro Pa',app.oaspl);
                    else
                        out7=sprintf('One-Third Octave SPL %s-scale  OASPL=%7.4g dB  Ref= 20 micro Pa',app.scale_label,app.oaspl);        
                    end
                else
                    if(app.iwn==1)
                        out7=sprintf('One Octave SPL   OASPL=%7.4g dB  Ref= 20 micro Pa',app.oaspl);
                    else
                        out7=sprintf('One Octave SPL %s-scale  OASPL=%7.4g dB  Ref= 20 micro Pa',app.scale_label,app.oaspl);        
                    end                    
                end
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','log','YScale','lin');
                    
                [xtt,xTT,app.iflag]=xtick_label(app.fmin,app.fmax);
            
                if(app.iflag==1)
                    set(gca,'xtick',xtt);
                    set(gca,'XTickLabel',xTT);
                    app.fmin=min(xtt);
                    app.fmax=max(xtt);
                    xlim([app.fmin,app.fmax]);    
                end   
                try
                    ylim([ymin,ymax]);
                catch
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
            %
              app.pf=fix_size(app.pf);
              app.pspl=fix_size(app.pspl);
              app.SPL=[app.pf app.pspl];
            %
                clear f;
            %
                bn=zeros(length(app.pspl),1);
                
                for i=1:length(app.pspl)
                    bn(i)=i;
                    f{i}=' ';
                    
                    for j=1:15
                            
                        if( (abs(app.pf(i)-app.a(j))/app.a(j)) < 0.05 )
                                            
                            if(j==11)
                                f{i}='1K';
                                break;
                            end
                            if(j==12)
                                f{i}='2K';
                                break;
                            end
                            if(j==13)
                                f{i}='4K';
                                break;
                            end
                            if(j==14)
                                f{i}='8K';
                                break;
                            end
                            if(j==15)
                                f{i}='16K';
                                break;
                            end               
                            f{i}=sprintf('%g',app.a(j));
                            break;
                        end    
              
                    end    
                       
                end
            %
                f=fix_size(f);
            %
                capp.pf(1)=app.pf(1);
                
                if(app.noct==1)
                    oct=2^(1/3);
                else
                    oct=2;
                end    

                for i=2:length(app.pf)
                    capp.pf(i)=capp.pf(i-1)*oct;
                end
            %
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                xx=log10(capp.pf);
                width=0.7;
                bar(xx,app.pspl,width);
                ylabel('SPL (dB)');
                xlabel('Center Frequency (Hz)');
            %    
                title(out7);
            %
                grid on;
                set(gca,'MinorGridLineStyle','none','GridLineStyle',':','XScale','lin','YScale','lin');
                set(gca,'Xtick',xx)
                set(gca,'XTickLabel',f)
            
                app.fmin=log10(app.fmin/1.2599);
                app.fmax=log10(app.fmax*1.2599);
            %
                xlim([app.fmin,app.fmax]);
                try
                    ylim([ymin,ymax]);
                catch
                end
            %  


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIvibrationdatasplUIFigure = uifigure('Visible', 'off');
            app.UIvibrationdatasplUIFigure.Name = 'UI vibrationdata spl';
            app.ReturnButton = uibutton(app.UIvibrationdatasplUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.vibrationdata_spl_aver17byTomIrvineLabel = uilabel(app.UIvibrationdatasplUIFigure);
            app.vibrationdata_spl_aver17byTomIrvineLabel.Text = 'vibrationdata_spl_a   ver 1.7  by Tom Irvine';
            app.TabGroup = uitabgroup(app.UIvibrationdatasplUIFigure);
            app.SPLTab = uitab(app.TabGroup);
            app.SPLTab.Title = 'SPL';
            app.Label = uilabel(app.SPLTab);
            app.Label.Text = 'This script calculates the sound pressure level of a time history.  The input array must have two columns: time(sec) & pressure';
            app.CalculateSPLButton = uibutton(app.SPLTab, 'push');
            app.CalculateSPLButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSPLButtonPushed, true);
            app.CalculateSPLButton.Text = 'Calculate SPL';
            app.PlotFrequenciesHzLabel = uilabel(app.SPLTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.InputPressureUnitListBoxLabel = uilabel(app.SPLTab);
            app.InputPressureUnitListBoxLabel.Text = 'Input Pressure Unit';
            app.InputPressureUnitListBox = uilistbox(app.SPLTab);
            app.InputPressureUnitListBox.Items = {'psi', 'Pascal', 'micro Pascal'};
            app.InputPressureUnitListBox.Value = 'psi';
            app.ProcessingListBoxLabel = uilabel(app.SPLTab);
            app.ProcessingListBoxLabel.Text = 'Processing';
            app.ProcessingListBox = uilistbox(app.SPLTab);
            app.ProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.ProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessingListBoxValueChanged2, true);
            app.ProcessingListBox.Value = 'Entire Signal';
            app.ApplyWeightingNetworkListBoxLabel = uilabel(app.SPLTab);
            app.ApplyWeightingNetworkListBoxLabel.Text = 'Apply Weighting Network?';
            app.ApplyWeightingNetworkListBox = uilistbox(app.SPLTab);
            app.ApplyWeightingNetworkListBox.Items = {'No (engineering)', 'A-scale', 'B-scale', 'C-scale'};
            app.ApplyWeightingNetworkListBox.Value = 'No (engineering)';
            app.MinEditFieldLabel = uilabel(app.SPLTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SPLTab, 'numeric');
            app.MinEditField.Value = 20;
            app.MaxEditFieldLabel = uilabel(app.SPLTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SPLTab, 'numeric');
            app.MaxEditField.Value = 10000;
            app.StartTimesecEditFieldLabel = uilabel(app.SPLTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.SPLTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.SPLTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.SPLTab, 'numeric');
            app.OutputSPLArrayNameEditFieldLabel = uilabel(app.SPLTab);
            app.OutputSPLArrayNameEditFieldLabel.Text = 'Output SPL Array Name';
            app.OutputSPLArrayNameEditField = uieditfield(app.SPLTab, 'text');
            app.OutputSPLArrayNameEditField.Value = 'output_spl';
            app.listbox_method = uilistbox(app.SPLTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.SPLTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.SPLTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.SPLTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.SPLTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.OctaveBandListBoxLabel = uilabel(app.SPLTab);
            app.OctaveBandListBoxLabel.Text = 'Octave Band';
            app.OctaveBandListBox = uilistbox(app.SPLTab);
            app.OctaveBandListBox.Items = {'One-Third', 'One'};
            app.OctaveBandListBox.Value = 'One-Third';
            app.WindowListBoxLabel = uilabel(app.SPLTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.SPLTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.Value = 'Hanning';
            app.MeanRemovalListBoxLabel = uilabel(app.SPLTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.SPLTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.Value = 'Yes';
            app.ReplotNewdBLimitsButton = uibutton(app.SPLTab, 'push');
            app.ReplotNewdBLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotNewdBLimitsButtonPushed, true);
            app.ReplotNewdBLimitsButton.Text = 'Replot New dB Limits';
            app.PNLTab = uitab(app.TabGroup);
            app.PNLTab.Title = 'PNL';
            app.Label_2 = uilabel(app.PNLTab);
            app.Label_2.Text = 'This script calculates the PNL for a one-third octave SPL, which is the equivalent sound pressure level at 1000 Hz.';
            app.TheinputfilemusthavetwocolumnsfcHzSPLdBref20microPaLabel = uilabel(app.PNLTab);
            app.TheinputfilemusthavetwocolumnsfcHzSPLdBref20microPaLabel.Text = 'The input file must have two columns:  fc(Hz) & SPL(dB)   ref 20 micro Pa';
            app.InputSPLArrayNameEditFieldLabel = uilabel(app.PNLTab);
            app.InputSPLArrayNameEditFieldLabel.Text = 'Input SPL Array Name';
            app.InputSPLArrayNameEditField = uieditfield(app.PNLTab, 'text');
            app.CalculatePNLButton = uibutton(app.PNLTab, 'push');
            app.CalculatePNLButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePNLButtonPushed, true);
            app.CalculatePNLButton.Text = 'Calculate PNL';
            % Show the figure after all components are created
            app.UIvibrationdatasplUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_spl_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIvibrationdatasplUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIvibrationdatasplUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIvibrationdatasplUIFigure)
        end
    end
end