classdef vibrationdata_statistics_Apph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uipanel_save                    matlab.ui.container.Panel
        edit_output_array               matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        listbox_save                    matlab.ui.control.ListBox
        pushbutton_save                 matlab.ui.control.Button
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        edit_results                    matlab.ui.control.TextArea
        uipanel1                        matlab.ui.container.Panel
        PlotLocalPeakHistogramCheckBox  matlab.ui.control.CheckBox
        UpdateListButton                matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        edit_title                      matlab.ui.control.EditField
        text6                           matlab.ui.control.Label
        edit_bars                       matlab.ui.control.EditField
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        pushbutton_return               matlab.ui.control.Button
        pushbutton_calculate            matlab.ui.control.Button
        text5                           matlab.ui.control.Label
        edit_ylabel                     matlab.ui.control.EditField
    end

    
    properties (Access = public)
         % Description
    end
    
    methods (Access = private)
        function slider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end                    
        end
        
        function goodbye(app)
            % try
            %    myHandle=evalin('base','myHandle');
            %    myHandle.delete;
            % catch
            % end
            % Honeybee_vibe;
        end
        
        function update(app)

            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                if(contains(vars(i).name,'myHandle') || contains(vars(i).name,'vname_list')) 
                    cell_array{i} ='999';
                else    
                    try
               
                        TTT=evalin('base',vars(i).name);
                        sz=size(TTT);

                        if(sz(2)==2)
                            cell_array{i} = vars(i).name;
                        else
                            cell_array{i} ='999';
                        end    

                    catch
                    end
                end    
            end
            for i=length(vars):-1:1
                if(contains(cell_array{i},'999'))
                    cell_array(i,:)=[];
                end
            end    

            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_statistics_OpeningFcn(app, varargin)
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_statistics (see VARARGIN)
            
            % Choose default command line output for vibrationdata_statistics
            handles.output = hObject;

            
            set(handles.listbox_method,'Value',1);
            
            set(handles.edit_results,'Enable','off');
            
            set(handles.uipanel_save,'Visible','off');

            update(app);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_method
        function listbox_method_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            % hObject    handle to listbox_method (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_method contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_method
            n=get(hObject,'Value');
            
            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   THM = fscanf(fid,'%g %g',[2 inf]);
                   THM=THM';
            
                   setappdata(0,'THM',THM);
            
                   size(THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            ClosePlots(app);

            k=get(handles.listbox_method,'Value');
            
            set(handles.uipanel_save,'Visible','on');
            
            set(handles.edit_results,'Enable','on');
            
            t_string=get(handles.edit_title,'String');
            tstring=t_string;
            
            YS=get(handles.edit_ylabel,'String');
            
            
            iflag=1;
            
            if(k==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     THM=evalin('base',FS);
                     iflag=1;

                 catch
                     iflag=0;
                     warndlg('Input Array does not exist.  Try reading in external file.')
                 end
            else
              THM=getappdata(0,'THM');
            end
            
            if(iflag==0)
                return;
            end
            
            amp=double(THM(:,2));
            tim=double(THM(:,1));
            t=tim;
            n = length(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                duration=tim(n)-tim(1);
                dt=duration/(n-1);
                difft=diff(t);
                dtmin=min(difft);
                dtmax=max(difft);
            %
                outa = sprintf('\n start  = %8.4g sec  ',t(1));
                outb = sprintf('\n end    = %8.4g sec  ',t(n));
            
                out4 = sprintf('\n dtmin  = %8.4g sec  ',dtmin);
                out5 = sprintf('\n dt     = %8.4g sec  ',dt);
                out6 = sprintf('\n dtmax  = %8.4g sec  ',dtmax);
            
            %
                out7 = sprintf('\n srmin  = %8.4g samples/sec  ',1/dtmax);
                out8 = sprintf('\n sr     = %8.4g samples/sec  ',1/dt);
                out9 = sprintf('\n srmax  = %8.4g samples/sec  ',1/dtmin);
            
            string1=sprintf(' %d samples ',n);
            
            string_tss=sprintf('\n\n Time Limits ');
            
            big_string=strcat(string1,string_tss);
            big_string=strcat(big_string,outa);
            big_string=strcat(big_string,outb);
            
            string_ts=sprintf('\n\n Time Step ');
            
            big_string=strcat(big_string,string_ts);
            big_string=strcat(big_string,out4);
            big_string=strcat(big_string,out5);
            big_string=strcat(big_string,out6);
            
            string_sr=sprintf('\n\n Sample Rate ');
            big_string=strcat(big_string,string_sr);
            big_string=strcat(big_string,out7);
            big_string=strcat(big_string,out8);
            big_string=strcat(big_string,out9);
            
            disp(big_string);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' ');
            disp(' Calculating skewness & kurtosis ');
            
            [mu,sd,rms,sk,kt]=kurtosis_stats(amp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp(' Calculating Zero & Peak crossings ');
            
            [pszcr,peak_rate,tpa,pa]=zero_crossing_function_alt(tim,amp,duration);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            string_amp=sprintf('\n\n Amplitude Stats ');
            string2=sprintf('\n      mean = %8.4g ',mu);
            string3=sprintf('\n   std dev = %8.4g ',sd);
            string4=sprintf('\n       RMS = %8.4g ',rms);
            string5=sprintf('\n  skewness = %8.4g ',sk);
            string6=sprintf('\n  kurtosis = %8.4g ',kt);
            
            big_string=strcat(big_string,string_amp);
            big_string=strcat(big_string,string2);
            big_string=strcat(big_string,string3);
            big_string=strcat(big_string,string4);
            big_string=strcat(big_string,string5);
            big_string=strcat(big_string,string6);
            
            
                mx=max(amp);
                mi=min(amp);
            
                amax=abs(mx);
                amin=abs(mi);
                if(amax<amin)
                    amax=amin;
                end
                crest=amax/rms;
            
            string7=sprintf('\n\n      Maximum = %8.4g ',mx);
            string8=sprintf('\n      Minimum = %8.4g ',mi);
            string9=sprintf('\n Crest Factor = %8.4g ',crest);
            
            
            big_string=strcat(big_string,string7);
            big_string=strcat(big_string,string8);
            big_string=strcat(big_string,string9);
            
            [rf]=Rice_frequency(amp,dt);
            
            %
                string_rf=sprintf('\n\n Rice Characteristic Frequency = %8.4g Hz ',rf);
            
                string_zc=sprintf('\n Positive Slope Zero Cross Rate = %8.4g Hz ',pszcr);
                string_pr=sprintf('\n Peak Rate = %8.4g Hz ',peak_rate);
            
            disp(string_rf);
            disp(string_zc);
            disp(string_pr);
            
            big_string=strcat(big_string,string_rf);
            big_string=strcat(big_string,string_zc);
            big_string=strcat(big_string,string_pr);
            
            set(handles.edit_results,'String',big_string);
            
            fig_num=1;
            
            try
                figure(fig_num);
                fig_num=fig_num+1;
                plot(THM(:,1),THM(:,2));
                title('Time History');
                xlabel(' Time(sec) ')
                ylabel(YS)
                grid on;
            catch
                warndlg('Plot time history failed');
                return;
            end

            try
                figure(fig_num);
                fig_num=fig_num+1;
                nbars=str2num(get(handles.edit_bars,'String'));
                xx=max(abs(THM(:,2)));
                x=linspace(-xx,xx,nbars);
                hist(THM(:,2),x)
                ylabel('Counts');
                title('Histogram');
                xlabel(YS);
                % qq=get(gca,'xlim');
            catch
                warndlg('ref 1: plot hist failed')
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            try
            
                x_label='Time (sec)';
                y_label=YS;
            
                nbars=str2num(get(handles.edit_bars,'String'));
            
                [fig_num]=plot_time_history_histogram(fig_num,THM,t_string,x_label,y_label,nbars);
            
            catch
                warndlg('Plot time history histogram failed')
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
                [nnn,xout]=hist(THM(:,2),nbars);
                c_elements = cumsum(nnn);
                figure(fig_num);
                fig_num=fig_num+1;
                bar(xout,c_elements);
                title(' Cumulative Histogram ');
                ylabel(' Counts ');
                xlabel(YS);
                grid on;
            catch
                warndlg('Plot cumulative histogram failed');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try

                figure(fig_num);
                fig_num=fig_num+1;
                xx=max(abs(pa));
                nbar=floor(nbars/2);
                x=linspace(0,xx,nbar);
                hist(pa,x)
                ylabel(' Counts');
                xlabel(YS);
                title('Absolute Peak Value Distribution');
                
                
                [i1,i2]=hist(THM(:,2),x);
                [p1,p2]=hist(pa,x);
                
                i1=fix_size(i1);
                i2=fix_size(i2);
                
                p1=fix_size(p1);
                p2=fix_size(p2);
                
                ii=[i1 i2];
                pp=[p1 p2];
                
                setappdata(0,'ii',ii);
                setappdata(0,'pp',pp);
                setappdata(0,'pa',pa);
            catch
                warndlg('plot hist failed');
                return;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(app.PlotLocalPeakHistogramCheckBox.Value)
            
                THM=[tpa pa];
            
                t_string='Local Peak Absolute Values';
                x_label='Time(sec)';
                y_label=YS;
                grid on;
            
                nbars=31;
            
                [fig_num]=plot_time_history_histogram_peak(fig_num,THM,t_string,x_label,y_label,nbars);
            
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            try
            
                [envp,envn]=time_history_envelope(THM(:,1),THM(:,2),dt,pszcr);
            
                figure(fig_num);
                fig_num=fig_num+1;
                plot(THM(:,1),THM(:,2),THM(:,1),envp,THM(:,1),envn);
                legend('Time History','Max Envelope','Min Envelope');
                title(tstring);
                xlabel('Time (sec)');
                ylabel(YS);
                grid on;
            
            catch
                warndlg('Plot time history envelope failed');
                return;
            end
            
            disp(' ');
            disp('plotting complete');
            disp(' ');
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)

            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_save,'Value');
            
            if(n==1)
                data=getappdata(0,'ii');
            end
            if(n==2)
                data=getappdata(0,'pp');
            end
            if(n==3)
                data=getappdata(0,'pa');
            end
            
            output_name=get(handles.edit_output_array,'String');
            assignin('base', output_name, data);

            update(app);

            msgbox('Save Complete');
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
 
            
            delete(app)
            
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_statistics';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.edit_ylabel = uieditfield(app.uipanel1, 'text');
            app.edit_ylabel.Tag = 'edit_ylabel';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Enter Time History Y-axis Label';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.listbox_method = uilistbox(app.uipanel1);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_method_Callback, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.edit_bars = uieditfield(app.uipanel1, 'text');
            app.edit_bars.Tag = 'edit_bars';
            app.edit_bars.Value = '31';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Enter Number of Histogram Bars';
            app.edit_title = uieditfield(app.uipanel1, 'text');
            app.edit_title.Tag = 'edit_title';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Enter Title';
            app.SelectInputArrayListBoxLabel = uilabel(app.uipanel1);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.uipanel1);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.UpdateListButton = uibutton(app.uipanel1, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.PlotLocalPeakHistogramCheckBox = uicheckbox(app.uipanel1);
            app.PlotLocalPeakHistogramCheckBox.Text = 'Plot Local Peak Histogram';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Results';
            app.uipanel2.Tag = 'uipanel2';
            app.edit_results = uitextarea(app.uipanel2);
            app.edit_results.Tag = 'edit_results';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'The input array must have two columns: time(sec) & amplitude';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'vibrationdata_statistics_Apph.m   ver 2.4  by Tom Irvine';
            app.uipanel_save = uipanel(app.figure1);
            app.uipanel_save.Title = 'Export to Matlab Workspace';
            app.uipanel_save.Tag = 'uipanel_save';
            app.pushbutton_save = uibutton(app.uipanel_save, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.listbox_save = uilistbox(app.uipanel_save);
            app.listbox_save.Items = {'Histogram, Instantaneous Values', 'Histogram, Peaks', 'Peaks'};
            app.listbox_save.Tag = 'listbox_save';
            app.listbox_save.Value = 'Histogram, Instantaneous Values';
            app.text9 = uilabel(app.uipanel_save);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Histogram';
            app.text10 = uilabel(app.uipanel_save);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Output Array';
            app.edit_output_array = uieditfield(app.uipanel_save, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_statistics_Apph(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_statistics_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end