classdef vibrationdata_stress_velocity_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIVibrationdataStressVelocityUIFigure  matlab.ui.Figure
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensitylbmin3EditField_sv   matlab.ui.control.NumericEditField
        MassDensitylbmin3EditField_svLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField_sv   matlab.ui.control.NumericEditField
        ElasticModuluspsiEditFieldLabel_sv  matlab.ui.control.Label
        MaterialListBox                 matlab.ui.control.ListBox
        MaterialListBoxLabel            matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
        vibrationdata_stress_velocity_aver13byTomIrvineLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        StressfromVelocityTab           matlab.ui.container.Tab
        insecLabel                      matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        BeamBendingTab                  matlab.ui.container.Tab
        CalculateButton_2               matlab.ui.control.Button
        BoundaryConditionsListBox       matlab.ui.control.ListBox
        BoundaryConditionsListBoxLabel  matlab.ui.control.Label
        CrossSectionListBox             matlab.ui.control.ListBox
        CrossSectionListBoxLabel        matlab.ui.control.Label
        PlateTab                        matlab.ui.container.Tab
        ProportionalityConstantEditField_plate  matlab.ui.control.EditField
        ProportionalityConstantEditField_plateLabel  matlab.ui.control.Label
        MethodListBox                   matlab.ui.control.ListBox
        MethodListBoxLabel              matlab.ui.control.Label
        CalculateButton_sv_plate        matlab.ui.control.Button
        LyinEditField                   matlab.ui.control.NumericEditField
        LyinEditFieldLabel              matlab.ui.control.Label
        LxinEditField                   matlab.ui.control.NumericEditField
        LxinEditFieldLabel              matlab.ui.control.Label
        BeamLongitudinalTab             matlab.ui.container.Tab
        CalculateButton_sv_longitudinal  matlab.ui.control.Button
        ComplexEquipmentTab             matlab.ui.container.Tab
        CalculateButton_sv_complex      matlab.ui.control.Button
        ProportionalityConstantEditField  matlab.ui.control.EditField
        ProportionalityConstantEditFieldLabel  matlab.ui.control.Label
        PeakModalVelocityEditField      matlab.ui.control.EditField
        PeakModalVelocityinsecLabel     matlab.ui.control.Label
        ResultsTextArea_sv              matlab.ui.control.TextArea
        ResultsTextAreaLabel_sv         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        VelocityLimitfromYieldStressTab  matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        YieldStressksiEditField         matlab.ui.control.NumericEditField
        YieldStressksiEditFieldLabel    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ThescriptcalculatesvelocitylimitsfromtheyieldstressLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        iu           % 
        n_structure  %
        n_mat        %
        rho          %
        E            %
        c            %  
        rho_c        %
        stress       %
        v            %
    end
    
    methods (Access = private)

        function plate_method(app)
            n=app.MethodListBox.Value;

            app.ProportionalityConstantEditField_plate.Visible='off';
            app.ProportionalityConstantEditField_plateLabel.Visible='off';
            app.LxinEditField.Visible='off';
            app.LxinEditFieldLabel.Visible='off';
            app.LyinEditField.Visible='off';
            app.LyinEditFieldLabel.Visible='off';
           

            if(n=='1')
                app.ProportionalityConstantEditField_plate.Visible='on';
                app.ProportionalityConstantEditField_plateLabel.Visible='on';
            else
                app.LxinEditField.Visible='on';
                app.LxinEditFieldLabel.Visible='on';
                app.LyinEditField.Visible='on';
                app.LyinEditFieldLabel.Visible='on';
            end
        end    

        function post(app)
            if(app.iu==1)
                sc=sprintf(' Wavespeed = %8.4g in/sec',app.c);                       
                ss=sprintf('\n\n Stress = %8.5g psi',app.stress); 
                ss2=sprintf('\n\n Stress = %8.5g ksi',app.stress/1000);   
            else
                sc=sprintf('Wavespeed = %8.4g m/sec',app.c);                       
                ss=sprintf('\n\n Stress = %8.5g Pa',app.stress);        
                ss2=sprintf('\n\n Stress = %8.5g MPa',app.stress/1000^2);                
            end    

            sq=strcat(sc,ss,ss2);
            
            app.ResultsTextArea_sv.Value=sq;
            app.ResultsTextArea_sv.Enable='on';            
            app.ResultsTextArea_sv.Visible='on'; 
            app.ResultsTextAreaLabel_sv.Visible='on';
        end

        function get_materials(app)

            app.v=str2num(app.PeakModalVelocityEditField.Value);
            
            if(isempty(app.v))
                warndlg('Enter Peak Modal Velocity');
                return;
            end

            app.E=app.ElasticModuluspsiEditField_sv.Value;
            
            app.rho=app.MassDensitylbmin3EditField_sv.Value;

            
            if(app.iu==1)
                app.rho=app.rho/386;
            else    
                [app.E]=GPa_to_Pa(app.E);
            end
            
            app.c=sqrt(app.E/app.rho);
            app.rho_c=app.rho*app.c;
                        
        end    
        
        function materials_change(app)

            Clear(app);
        
            ius=app.SelectUnitsListBox.Value;
            
            if(strcmp(ius,'English'))
                app.iu=1;
            else    
                app.iu=2;
            end
        
 
            nms=app.MaterialListBox.Value;
            
            if(strcmp(nms,'Aluminum'))
                app.n_mat=1;
            end
            if(strcmp(nms,'Steel'))
                app.n_mat=2;
            end            
            if(strcmp(nms,'Copper'))
                app.n_mat=3;
            end  
            if(strcmp(nms,'Other'))
                app.n_mat=4;
            end              
            
            %
            if(app.iu==1)  % English
                
                app.ElasticModuluspsiEditFieldLabel_sv.Text='Elastic Modulus (psi)';    
                app.MassDensitylbmin3EditField_svLabel.Text='Mass Density (lbm/in^3)';
                app.insecLabel.Text='(in/sec)';
        
                app.LxinEditFieldLabel.Text='Lx (in)';
                app.LyinEditFieldLabel.Text='Ly (in)';                
                
                if(app.n_mat==1) % aluminum
                    elastic_modulus=1e+007;
                    mass_density=0.1;  
                end  
                if(app.n_mat==2)  % steel
                    elastic_modulus=3e+007;
                    mass_density= 0.28;         
                end
                if(app.n_mat==3)  % copper
                    elastic_modulus=1.6e+007;
                    mass_density=  0.322;
                end
            else                 % metric
                
                app.ElasticModuluspsiEditFieldLabel_sv.Text='Elastic Modulus (GPa)';  
                app.MassDensitylbmin3EditField_svLabel.Text='Mass Density (kg/m^3)';
                app.insecLabel.Text='(m/sec)';             
                
                app.LxinEditFieldLabel.Text='Lx (m)';
                app.LyinEditFieldLabel.Text='Ly (m)';

                if(app.n_mat==1)  % aluminum
                    elastic_modulus=70;
                    mass_density=  2700;
                end
                if(app.n_mat==2)  % steel
                    elastic_modulus=205;
                    mass_density=  7700;        
                end
                if(app.n_mat==3)   % copper
                    elastic_modulus=110;
                    mass_density=  8900;
                end
            end
            
            if(app.n_mat<=3)
                app.ElasticModuluspsiEditField_sv.Value=elastic_modulus;
                app.MassDensitylbmin3EditField_sv.Value=mass_density;             
            end

             
        end
        function Clear(app)
            app.ResultsTextArea.Enable='off'; 
            app.ResultsTextArea.Visible='off'; 
            app.ResultsTextArea_sv.Enable='off'; 
            app.ResultsTextArea_sv.Visible='off'; 
            app.ResultsTextAreaLabel_sv.Visible='off';
        end    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Clear(app);
            materials_change(app);
            plate_method(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.E=app.ElasticModuluspsiEditField_sv.Value;
            app.rho=app.MassDensitylbmin3EditField_sv.Value;
            Y=app.YieldStressksiEditField.Value;
            
            if(app.iu==1)
                app.rho=app.rho/386;
                Y=Y*1000;
            else 
                [Y]=GPa_to_Pa(Y);
            end                
   
            app.c=sqrt(app.E/app.rho);
            
            rhoc=app.rho*app.c;

            vbar=Y/rhoc;
            vbeam=vbar/sqrt(3);
            vpipe=vbar/sqrt(2);
            vplate=vbar/2;
            vcyl=vplate;
            
            AE=Y^2/(2*app.E);
            
            s1=sprintf('\n Speed of sound = %7.3g in/sec',app.c);
            s2=sprintf('\n\n Velocity Rod Longitudinal = %7.3g in/sec',vbar);
            s3=sprintf('\n Velocity Pipe Thin-walled Bending = %7.3g in/sec',vpipe);
            s4=sprintf('\n\n Velocity Beam Rectangular Bending = %7.3g in/sec',vbeam);
            s5=sprintf('\n Velocity Beam Solid Cylinder Bending = %7.3g in/sec',vcyl);
            s6=sprintf('\n Velocity Plate Bending = %7.3g in/sec',vplate);
            s7=sprintf('\n\n Absorbed Energy per Volume = %7.3g psi',AE);
            
            app.ResultsTextArea.Enable='on';                         
            str = strcat(s1,s2,s3,s4,s5,s6,s7);  

            app.ResultsTextArea.Value=str;       
            app.ResultsTextArea.Enable='on';
            app.ResultsTextArea.Visible='on';            
                     
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            app.ResultsTextArea.Enable='off'; 
        end

        % Callback function
        function ElasticModuluspsiEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off'; 
        end

        % Callback function
        function MassDensitylbmin3EditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off'; 
        end

        % Value changed function: YieldStressksiEditField
        function YieldStressksiEditFieldValueChanged(app, event)
            app.ResultsTextArea.Enable='off';            
        end

        % Callback function: not associated with a component
        function CalculateButton_beamPushed(app, event)
            app.v=str2num(app.PeakModalVelocityEditField.Value);
            
            if(isempty(app.v))
                warndlg('Enter Peak Modal Velocity');
                return;
            end
            
            app.E=app.ElasticModuluspsiEditField_sv.Value;
            
            app.rho=app.MassDensitylbmin3EditField_sv.Value;

            
            if(app.iu==1)
                app.rho=app.rho/386;
            else    
                [app.E]=GPa_to_Pa(app.E);
            end
            
            app.c=sqrt(app.E/app.rho);
            app.rho_c=app.rho*app.c;
                        
            if(app.n_structure==1) % beam bending
                
                c_hat=str2num(app.ProportionalityConstantEditField.Value);
                app.stress=c_hat*app.rho_c*app.v;
            
            end
            if(app.n_structure==2) % beam longitudinal
                app.stress=app.rho_c*app.v;
            end
            if(app.n_structure==3) % plate 1
                c_hat=str2num(app.ProportionalityConstantEditField.Value);
                app.stress=c_hat*app.rho_c*app.v;  
            end
            if(app.n_structure==4) % plate 2
                
                Lx=app.LxinEditField.Value;
                Ly=app.LyinEditField.Value;
                mu=app.PoissonRatioEditField.Value;
                
                Lx2=Lx^2;
                Ly2=Ly^2;
                
                den=Lx2+Ly2;
                
                term0=app.rho_c*app.v;
                
                term1=sqrt(3/(1-mu^2));
                termx=( Ly2+mu*Lx2)/den;
                termy=( Lx2+mu*Ly2)/den;    
                
                stress1=term1*termx;   
                stress2=term1*termy; 
                
                app.stress=term0*(max([stress1 stress2]));
                
            end
            if(app.n_structure==5) % complex equipment
                c_hat=str2num(app.ProportionalityConstantEditField.Value);
                app.stress=c_hat*app.rho_c*app.v;    
            end

            if(app.iu==1)
                sc=sprintf(' Wavespeed = %8.4g in/sec',app.c);                       
                ss=sprintf('\n\n Stress = %8.5g psi',app.stress); 
                ss2=sprintf('\n\n Stress = %8.5g ksi',app.stress/1000);   
            else
                sc=sprintf('Wavespeed = %8.4g m/sec',app.c);                       
                ss=sprintf('\n\n Stress = %8.5g Pa',app.stress);        
                ss2=sprintf('\n\n Stress = %8.5g MPa',app.stress/1000^2);                
            end    

            sq=strcat(sc,ss,ss2);
            
            app.ResultsTextArea_sv.Value=sq;
            app.ResultsTextArea_sv.Enable='on';            
            app.ResultsTextArea_sv.Visible='on'; 
            app.ResultsTextAreaLabel_sv.Visible='on';
        end

        % Callback function
        function MaterialListBoxValueChanged(app, event)
            materials_change(app);
            app.ResultsTextArea_sv.Enable='off';            
        end

        % Callback function: not associated with a component
        function StructureListBoxValueChanged(app, event)
            materials_change(app);   % to get app.n_structure
            app.ResultsTextArea_sv.Enable='off';       
        end

        % Value changed function: CrossSectionListBox
        function CrossSectionListBoxValueChanged(app, event)
            materials_change(app);
            app.ResultsTextArea_sv.Enable='off';   
        end

        % Callback function
        function SelectUnitsListBoxValueChanged(app, event)
            materials_change(app);
            app.ResultsTextArea_sv.Enable='off';
        end

        % Callback function
        function PeakModalVelocityinsecEditFieldValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Value changed function: LxinEditField
        function LxinEditFieldValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Value changed function: LyinEditField
        function LyinEditFieldValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Callback function
        function ElasticModuluspsiEditField_svValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';            
        end

        % Callback function
        function MassDensitylbmin3EditField_svValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Callback function
        function PoissonRatioEditFieldValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Callback function
        function ProportionalityConstantEditFieldValueChanged(app, event)
            app.ResultsTextArea_sv.Enable='off';
        end

        % Value changed function: SelectUnitsListBox
        function SelectUnitsListBoxValueChanged2(app, event)
            app.ResultsTextArea_sv.Enable='off';
            materials_change(app);
        end

        % Value changed function: MaterialListBox
        function MaterialListBoxValueChanged2(app, event)
            materials_change(app);
            app.ResultsTextArea_sv.Enable='off';           
        end

        % Value changing function: PeakModalVelocityEditField
        function PeakModalVelocityEditFieldValueChanging(app, event)
            app.ResultsTextArea_sv.Enable='off';
            app.ResultsTextArea_sv.Visible='off'; 
        end

        % Value changing function: ProportionalityConstantEditField
        function ProportionalityConstantEditFieldValueChanging(app, event)
            app.ResultsTextArea_sv.Enable='off';
            app.ResultsTextArea_sv.Visible='off';
        end

        % Button pushed function: CalculateButton_sv_complex
        function CalculateButton_sv_complexPushed(app, event)
            get_materials(app);
            c_hat=str2num(app.ProportionalityConstantEditField.Value);
            app.stress=c_hat*app.rho_c*app.v;
            post(app);
        end

        % Button pushed function: CalculateButton_sv_longitudinal
        function CalculateButton_sv_longitudinalPushed(app, event)
            get_materials(app);
            app.stress=app.rho_c*app.v;
            post(app);
        end

        % Button pushed function: CalculateButton_sv_plate
        function CalculateButton_sv_platePushed(app, event)
            get_materials(app);

            n=app.MethodListBox.Value;

            if(n=='1')
                c_hat=str2num(app.ProportionalityConstantEditField_plate.Value);
                app.stress=c_hat*app.rho_c*app.v;  
            else
                Lx=app.LxinEditField.Value;
                Ly=app.LyinEditField.Value;
                mu=app.PoissonRatioEditField.Value;
                
                Lx2=Lx^2;
                Ly2=Ly^2;
                
                den=Lx2+Ly2;
                
                term0=app.rho_c*app.v;
                
                term1=sqrt(3/(1-mu^2));
                termx=( Ly2+mu*Lx2)/den;
                termy=( Lx2+mu*Ly2)/den;    
                
                stress1=term1*termx;   
                stress2=term1*termy; 
                
                app.stress=term0*(max([stress1 stress2]));
            end    

            post(app);
        end

        % Value changed function: MethodListBox
        function MethodListBoxValueChanged(app, event)
    
            plate_method(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            get_materials(app);

            Items = strtrim(app.CrossSectionListBox.Items);
            im = strtrim(app.CrossSectionListBox.Value);
            i = strcmp(Items, im);

            Items = strtrim(app.BoundaryConditionsListBox.Items);
            im = strtrim(app.BoundaryConditionsListBox.Value);
            j = strcmp(Items, im);
            
            m(1,:)=[ 2	        2.67	2.52	1.60  ];
            m(2,:)=[ sqrt(2)	1.89	1.78	1.13  ];
            m(3,:)=[ sqrt(3)	2.31	2.18	1.39  ];
            m(4,:)=[ sqrt(6)/2	1.63	1.54	0.98  ];

            c_hat=m(i,j);
            app.stress=c_hat*app.rho_c*app.v;

            post(app);
        end

        % Value changed function: BoundaryConditionsListBox
        function BoundaryConditionsListBoxValueChanged(app, event)
            
            Clear(app);
        end

        % Value changed function: PeakModalVelocityEditField
        function PeakModalVelocityEditFieldValueChanged(app, event)
        
            Clear(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIVibrationdataStressVelocityUIFigure = uifigure('Visible', 'off');
            app.UIVibrationdataStressVelocityUIFigure.Name = 'UI Vibrationdata Stress Velocity';
            app.TabGroup = uitabgroup(app.UIVibrationdataStressVelocityUIFigure);
            app.StressfromVelocityTab = uitab(app.TabGroup);
            app.StressfromVelocityTab.Title = 'Stress from Velocity';
            app.Label = uilabel(app.StressfromVelocityTab);
            app.Label.Text = 'The script calculates the maximum global stress in a structure undergoing free vibration or driven at resonance.';
            app.ResultsTextAreaLabel_sv = uilabel(app.StressfromVelocityTab);
            app.ResultsTextAreaLabel_sv.Text = 'Results';
            app.ResultsTextArea_sv = uitextarea(app.StressfromVelocityTab);
            app.PeakModalVelocityinsecLabel = uilabel(app.StressfromVelocityTab);
            app.PeakModalVelocityinsecLabel.Text = 'Peak Modal Velocity ';
            app.PeakModalVelocityEditField = uieditfield(app.StressfromVelocityTab, 'text');
            app.PeakModalVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @PeakModalVelocityEditFieldValueChanged, true);
            app.PeakModalVelocityEditField.ValueChangingFcn = createCallbackFcn(app, @PeakModalVelocityEditFieldValueChanging, true);
            app.TabGroup2 = uitabgroup(app.StressfromVelocityTab);
            app.BeamBendingTab = uitab(app.TabGroup2);
            app.BeamBendingTab.Title = 'Beam Bending';
            app.CrossSectionListBoxLabel = uilabel(app.BeamBendingTab);
            app.CrossSectionListBoxLabel.Text = 'Cross Section';
            app.CrossSectionListBox = uilistbox(app.BeamBendingTab);
            app.CrossSectionListBox.Items = {'Solid Circular', 'Thin Wall Pipe', 'Solid Rectangular', 'Thin Hollow Square'};
            app.CrossSectionListBox.ValueChangedFcn = createCallbackFcn(app, @CrossSectionListBoxValueChanged, true);
            app.CrossSectionListBox.Value = 'Solid Circular';
            app.BoundaryConditionsListBoxLabel = uilabel(app.BeamBendingTab);
            app.BoundaryConditionsListBoxLabel.Text = 'Boundary Conditions';
            app.BoundaryConditionsListBox = uilistbox(app.BeamBendingTab);
            app.BoundaryConditionsListBox.Items = {'Simply-Supported & Cantilever', 'Fixed-Pinned', 'Fixed-Fixed', 'Free-Free'};
            app.BoundaryConditionsListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionsListBoxValueChanged, true);
            app.BoundaryConditionsListBox.Value = 'Simply-Supported & Cantilever';
            app.CalculateButton_2 = uibutton(app.BeamBendingTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PlateTab = uitab(app.TabGroup2);
            app.PlateTab.Title = 'Plate';
            app.LxinEditFieldLabel = uilabel(app.PlateTab);
            app.LxinEditFieldLabel.Text = 'Lx (in)';
            app.LxinEditField = uieditfield(app.PlateTab, 'numeric');
            app.LxinEditField.ValueChangedFcn = createCallbackFcn(app, @LxinEditFieldValueChanged, true);
            app.LyinEditFieldLabel = uilabel(app.PlateTab);
            app.LyinEditFieldLabel.Text = 'Ly (in)';
            app.LyinEditField = uieditfield(app.PlateTab, 'numeric');
            app.LyinEditField.ValueChangedFcn = createCallbackFcn(app, @LyinEditFieldValueChanged, true);
            app.CalculateButton_sv_plate = uibutton(app.PlateTab, 'push');
            app.CalculateButton_sv_plate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sv_platePushed, true);
            app.CalculateButton_sv_plate.Text = 'Calculate';
            app.MethodListBoxLabel = uilabel(app.PlateTab);
            app.MethodListBoxLabel.Text = 'Method';
            app.MethodListBox = uilistbox(app.PlateTab);
            app.MethodListBox.Items = {'1', '2'};
            app.MethodListBox.ValueChangedFcn = createCallbackFcn(app, @MethodListBoxValueChanged, true);
            app.MethodListBox.Value = '1';
            app.ProportionalityConstantEditField_plateLabel = uilabel(app.PlateTab);
            app.ProportionalityConstantEditField_plateLabel.Text = 'Proportionality Constant';
            app.ProportionalityConstantEditField_plate = uieditfield(app.PlateTab, 'text');
            app.ProportionalityConstantEditField_plate.Value = '2';
            app.BeamLongitudinalTab = uitab(app.TabGroup2);
            app.BeamLongitudinalTab.Title = 'Beam Longitudinal';
            app.CalculateButton_sv_longitudinal = uibutton(app.BeamLongitudinalTab, 'push');
            app.CalculateButton_sv_longitudinal.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sv_longitudinalPushed, true);
            app.CalculateButton_sv_longitudinal.Text = 'Calculate';
            app.ComplexEquipmentTab = uitab(app.TabGroup2);
            app.ComplexEquipmentTab.Title = 'Complex Equipment';
            app.ProportionalityConstantEditFieldLabel = uilabel(app.ComplexEquipmentTab);
            app.ProportionalityConstantEditFieldLabel.Text = 'Proportionality Constant';
            app.ProportionalityConstantEditField = uieditfield(app.ComplexEquipmentTab, 'text');
            app.ProportionalityConstantEditField.ValueChangingFcn = createCallbackFcn(app, @ProportionalityConstantEditFieldValueChanging, true);
            app.ProportionalityConstantEditField.Value = '2';
            app.CalculateButton_sv_complex = uibutton(app.ComplexEquipmentTab, 'push');
            app.CalculateButton_sv_complex.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_sv_complexPushed, true);
            app.CalculateButton_sv_complex.Text = 'Calculate';
            app.insecLabel = uilabel(app.StressfromVelocityTab);
            app.insecLabel.Text = 'in/sec';
            app.VelocityLimitfromYieldStressTab = uitab(app.TabGroup);
            app.VelocityLimitfromYieldStressTab.Title = 'Velocity Limit from Yield Stress';
            app.ThescriptcalculatesvelocitylimitsfromtheyieldstressLabel = uilabel(app.VelocityLimitfromYieldStressTab);
            app.ThescriptcalculatesvelocitylimitsfromtheyieldstressLabel.Text = 'The script calculates velocity limits from the yield stress.';
            app.CalculateButton = uibutton(app.VelocityLimitfromYieldStressTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.YieldStressksiEditFieldLabel = uilabel(app.VelocityLimitfromYieldStressTab);
            app.YieldStressksiEditFieldLabel.Text = 'Yield Stress (ksi)';
            app.YieldStressksiEditField = uieditfield(app.VelocityLimitfromYieldStressTab, 'numeric');
            app.YieldStressksiEditField.ValueChangedFcn = createCallbackFcn(app, @YieldStressksiEditFieldValueChanged, true);
            app.ResultsTextAreaLabel = uilabel(app.VelocityLimitfromYieldStressTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.VelocityLimitfromYieldStressTab);
            app.vibrationdata_stress_velocity_aver13byTomIrvineLabel = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.vibrationdata_stress_velocity_aver13byTomIrvineLabel.Text = 'vibrationdata_stress_velocity_a     ver 1.3  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIVibrationdataStressVelocityUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.MaterialListBoxLabel = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.MaterialListBoxLabel.Text = 'Material';
            app.MaterialListBox = uilistbox(app.UIVibrationdataStressVelocityUIFigure);
            app.MaterialListBox.Items = {'Aluminum', 'Steel', 'Copper', 'Other'};
            app.MaterialListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialListBoxValueChanged2, true);
            app.MaterialListBox.Value = 'Aluminum';
            app.ElasticModuluspsiEditFieldLabel_sv = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.ElasticModuluspsiEditFieldLabel_sv.Text = 'Elastic Modulus (psi)';
            app.ElasticModuluspsiEditField_sv = uieditfield(app.UIVibrationdataStressVelocityUIFigure, 'numeric');
            app.MassDensitylbmin3EditField_svLabel = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.MassDensitylbmin3EditField_svLabel.Text = 'Mass Density (lbm/in^3)';
            app.MassDensitylbmin3EditField_sv = uieditfield(app.UIVibrationdataStressVelocityUIFigure, 'numeric');
            app.PoissonRatioEditFieldLabel = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.UIVibrationdataStressVelocityUIFigure, 'numeric');
            app.PoissonRatioEditField.Value = 0.3;
            app.SelectUnitsListBoxLabel = uilabel(app.UIVibrationdataStressVelocityUIFigure);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.UIVibrationdataStressVelocityUIFigure);
            app.SelectUnitsListBox.Items = {'English', 'metric'};
            app.SelectUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectUnitsListBoxValueChanged2, true);
            app.SelectUnitsListBox.Value = 'English';
            % Show the figure after all components are created
            app.UIVibrationdataStressVelocityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_stress_velocity_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIVibrationdataStressVelocityUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIVibrationdataStressVelocityUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIVibrationdataStressVelocityUIFigure)
        end
    end
end