classdef vibrationdata_tvfa_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel_save           matlab.ui.container.Panel
        listbox_function       matlab.ui.control.ListBox
        text16                 matlab.ui.control.Label
        edit_output_array      matlab.ui.control.EditField
        text15                 matlab.ui.control.Label
        pushbutton_save        matlab.ui.control.Button
        text13                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        edit_tend              matlab.ui.control.EditField
        edit_tstart            matlab.ui.control.EditField
        text18                 matlab.ui.control.Label
        text17                 matlab.ui.control.Label
        listbox_overlap        matlab.ui.control.ListBox
        text14                 matlab.ui.control.Label
        edit_yaxis_label       matlab.ui.control.EditField
        edit_segment_duration  matlab.ui.control.EditField
        text5                  matlab.ui.control.Label
        pushbutton_return      matlab.ui.control.Button
        pushbutton_calculate   matlab.ui.control.Button
        listbox_method         matlab.ui.control.ListBox
        text2                  matlab.ui.control.Label
        text_input_array_name  matlab.ui.control.Label
        edit_input_array       matlab.ui.control.EditField
        text11                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_tvfa_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vibrationdata_tvfa (see VARARGIN)
            
            % Choose default command line output for vibrationdata_tvfa
            handles.output = hObject;
            
            set(handles.uipanel_save,'Visible','off');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: listbox_method
        function listbox_method_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_method (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_method contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_method
            
            set(handles.uipanel_save,'Visible','off');
            
            n=get(hObject,'Value');
            
            set(handles.text_input_array_name,'Visible','on');
            set(handles.edit_input_array,'Visible','on');
            
            set(handles.edit_input_array,'String',' ');
            
            if(n==1)
               set(handles.edit_input_array,'enable','on')
            else
            
               set(handles.text_input_array_name,'Visible','off');
               set(handles.edit_input_array,'Visible','off');
            
               set(handles.edit_input_array,'enable','off')
            
               [filename, pathname] = uigetfile('*.*');
               filename = fullfile(pathname, filename);
               fid = fopen(filename,'r');
               THM = fscanf(fid,'%g %g',[2 inf]);
               THM=THM';
            
               setappdata(0,'THM',THM);
            end
        end

        % Value changed function: listbox_overlap
        function listbox_overlap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_overlap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_overlap contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_overlap
            set(handles.uipanel_save,'Visible','off');
        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %%%
            
            Figures = findobj( 'Type', 'Figure' , '-not' , 'Tag' , get( handles.output , 'Tag' ) );
            NFigures = length( Figures );
            for nFigures = 1 : NFigures
              close( Figures( nFigures ) );
            end
            
            fig_num=1;
            
            %%%
            
            k=get(handles.listbox_method,'Value');
            
            YS=get(handles.edit_yaxis_label,'String');
            
            
            p=get(handles.listbox_overlap,'Value');
            
            po=(p-1)*0.1;
            
            
            if(k==1)
              FS=get(handles.edit_input_array,'String');
              THM=evalin('base',FS);
            else
              THM=getappdata(0,'THM');
            end
            
            
            tstart=str2num(get(handles.edit_tstart,'String'));
            tend=str2num(get(handles.edit_tend,'String'));
            
            [~,i]=min(abs(tstart-THM(:,1)));
            [~,j]=min(abs(tend-THM(:,1)));
            
            THM=THM(i:j,:);
            
            
            seg=str2num(get(handles.edit_segment_duration,'String'));
            
            if(seg<1.0e-05)
                warndlg('Segment Duration is too low');
                return;
            end
            
            amp=THM(:,2);
            tim=THM(:,1);
            nn = size(amp);
            n = nn(1);
            %disp(' mean values ')
            mu=mean(amp);
            sd=std(amp);
            mx=max(amp);
            mi=min(amp);
            rms=sqrt(sd^2+mu^2);
            kta=0.;
            tt_max=0.;
            tt_min=0.;
            %1
                for i=1:n
                    if( amp(i)==mx)
                        tt_max=tim(i);
                    end
                    if( amp(i)==mi)
                        tt_min=tim(i);
                    end
                    kta=kta+amp(i)^4;
                end
                kta=kta/(n*sd^4);
            %1
            disp(' ')
            disp(' time stats ')
            disp(' ')
            tmx=max(tim);
            tmi=min(tim);
            % disp(out0)
            out3 = sprintf(' start  = %g sec    end = %g sec            ',tmi,tmx);
            dt=(tmx-tmi)/n;
            sr=1./dt;
            out4 = sprintf(' SR  = %8.4g samples/sec    dt = %8.4g sec            ',sr,dt);
            out5 = sprintf('\n number of samples = %d  ',n);
            disp(out3)
            disp(out4)
            disp(out5)
            disp(' ')
            disp(' amplitude stats ')
            disp(' ')
            out0 = sprintf(' number of points = %d ',n);
            out1 = sprintf(' mean = %8.4g    std = %8.4g    rms = %8.4g ',mu,sd,rms);
            out2a = sprintf(' max  = %9.4g  at  = %8.4g sec            ',mx,tt_max);
            out2b = sprintf(' min  = %9.4g  at  = %8.4g sec            ',mi,tt_min);
            out5  = sprintf('\n kurtosis  = %8.4g ',kta);
            %
            disp(out1)
            disp(out2a)
            disp(out2b)
            disp(out5)
            %
            dur=tmx-tmi;
            %
            if(seg>dur/4)
                seg=dur/4;
                string=sprintf('%8.3g',seg);
                set(handles.edit_segment_duration,'String',string);
            end
            %
            
            ns=fix(sr*seg);
            %
            out1=sprintf(' ns=%d ',ns);
            disp(out1);
            
            step=floor(ns*(1-po));
            
            
            i=1;
            j1=1;
            j2=1+ns;
            
            maxn=1.0e+06;
            
            while(1)
            
                if((j2)>n)
                    break;
                end
            
                j1=j1+step;
                j2=j2+step;
            
                i=i+1;
            
                if(i>maxn)
                    break;
                end
            
            end
            
            nnn=i;
            
            
            disp(' ');
            out1=sprintf('\n nnn=%d  step=%d\n',nnn,step);
            disp(out1);


            if(nnn==0)
                warndlg('nnn=0');
                return;
            end
            if(step==0)
                warndlg('step=0');
                return;
            end


            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            tpi=2*pi;
            
            zc=zeros(nnn,1);
            sds=zeros(nnn,1);
            rms=zeros(nnn,1);
            av=zeros(nnn,1);
            peak=zeros(nnn,1);
            tt=zeros(nnn,1);
            freq=zeros(nnn,1);
            kt=zeros(nnn,1);
            
            %
            j1=1;
            j2=1+ns;
            
            for i=1:nnn
                if(j2>n)
                    break;
                end
            %
                clear x;
                zc(i)=0;
                x=amp(j1:j2);
            
                if(isempty(x))
                    break;
                end
            
            %    peak(i)=max(abs(x));
            
                [mu,sd,xrms,~,xkt]=kurtosis_stats(x);
            
                av(i)=mu;
                sds(i)=sd;
                rms(i)=xrms;
                kt(i)=xkt;
            
                tt(i)=(tim(j1)+tim(j2))/2.;
            %
                for k=2:max(size(x))
                    if(x(k)*x(k-1)<0)
                        zc(i)=zc(i)+1;
                    end
                end
            
            %%%%
            
                nfr=1;
                a=x;
                amp_orig=x;
                t=tim(j1:j2);
                dur=t(end)-t(1);
                tt(i)=mean(t);
            
                [~,~,Ar,Br,omeganr]=sine_find_function(dur,a,amp_orig,t,dt,nfr);
            %    peak(i)=norm([Ar Br]);
            
                peak(i)=max(abs(x));
            
                freq(i)=omeganr/tpi;
            
            %%%%
            
                j1=j1+step;
                j2=j2+step;
            %
            end
            %
            
            freq=fix_size(freq);
            %
            clear length;
            n=length(tt);
            if(tt(n)<tt(n-1))
                tt(n)=[];
                sds(n)=[];
                av(n)=[];
                peak(n)=[];
                rms(n)=[];
                zc(n)=[];
                freq(n)=[];
                kt(n)=[];
            end
            %
            
            for i=1:length(kt)
                if(isnan(kt(i)))
                    kt(i)=0;
                end
            end
            
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tim,amp)
            xlabel(' Time (sec)');
            ylabel(YS);
            grid on;
            title('Time History');
            %
            tt
            kt
            %
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,kt)
            xlabel(' Time (sec)');
            ylabel('Kurtosis');
            grid on;
            title('Kurtosis Time History');
            %


            figure(fig_num);
            fig_num=fig_num+1;
            
            plot(tt,peak,tt,rms,tt,sds,'-.',tt,av);
            
            setappdata(0,'peak',[tt peak]);
            setappdata(0,'RMS',[tt rms]);
            setappdata(0,'stddev',[tt sds]);
            setappdata(0,'mean',[tt av]);
            setappdata(0,'kurtosis',[tt kt]);
            
            legend ('peak','rms','std dev','average');
            xlabel(' Time (sec)');
            ylabel(YS);
            title('Segmented Time History');
            grid;
            %% ymax=max(av);
            %% if( max(av) < max(peak))
            %%     ymax=max(peak);
            %% end
            %% ymax=ymax*1.2;
            %% ymin=min(av);
            %% axis([tmi tmx ymin ymax]);
            %
            
            
            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,freq);
            setappdata(0,'frequency',[tt freq]);
            title('Frequency vs. Time');
            xlabel(' Time (sec)');
            ylabel(' Freq (Hz)');
            grid;
            % ymax=max(freq)*1.2;
            % ymin=min(freq)*0.7;
            % axis([tmi tmx ymin ymax]);
            %


            figure(fig_num);
            fig_num=fig_num+1;
            plot(tt,freq,'.');
            setappdata(0,'frequency',[tt freq]);
            title('Frequency vs. Time');
            xlabel(' Time (sec)');
            ylabel(' Freq (Hz)');
            grid;
            % ymax=max(freq)*1.2;
            % ymin=min(freq)*0.7;
            % axis([tmi tmx ymin ymax]);
            %
            
            
            figure(fig_num);
            plot(freq,peak,'.');
            title('Peak vs. Frequency');
            string=sprintf('Peak %s',YS);
            ylabel(string);
            xlabel(' Freq (Hz)');
            grid;
            % ymax=max(peak)*1.2;
            % ymin=0;
            % fmi=min(freq);
            % fmx=max(freq);
            % axis([fmi fmx ymin ymax]);
            
            set(handles.uipanel_save,'Visible','on');
        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n=get(handles.listbox_function,'Value');
            n
            
            if(n==1)
                data=getappdata(0,'peak');
            end
            if(n==2)
                data=getappdata(0,'RMS');
            end
            if(n==3)
                data=getappdata(0,'stddev');
            end
            if(n==4)
                data=getappdata(0,'mean');
            end
            if(n==5)
                data=getappdata(0,'frequency');
            end
            if(n==6)
                data=getappdata(0,'kurtosis');
            end
            
            output_name=get(handles.edit_output_array,'String');
            assignin('base', output_name, data);
            
            h = msgbox('Save Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'vibrationdata_tvfa';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Input Data';
            app.uipanel1.Tag = 'uipanel1';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Enter Y-axis Label';
            app.edit_input_array = uieditfield(app.uipanel1, 'text');
            app.edit_input_array.Tag = 'edit_input_array';
            app.text_input_array_name = uilabel(app.uipanel1);
            app.text_input_array_name.Tag = 'text_input_array_name';
            app.text_input_array_name.Text = 'Input Array Name';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Data Input Method';
            app.listbox_method = uilistbox(app.uipanel1);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_method_Callback, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Enter Segment Duration (sec)';
            app.edit_segment_duration = uieditfield(app.uipanel1, 'text');
            app.edit_segment_duration.Tag = 'edit_segment_duration';
            app.edit_yaxis_label = uieditfield(app.uipanel1, 'text');
            app.edit_yaxis_label.Tag = 'edit_yaxis_label';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Percent Overlap';
            app.listbox_overlap = uilistbox(app.uipanel1);
            app.listbox_overlap.Items = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.listbox_overlap.ValueChangedFcn = createCallbackFcn(app, @listbox_overlap_Callback, true);
            app.listbox_overlap.Tag = 'listbox_overlap';
            app.listbox_overlap.Value = '0';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Start Time (sec)';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'End Time (sec)';
            app.edit_tstart = uieditfield(app.uipanel1, 'text');
            app.edit_tstart.Tag = 'edit_tstart';
            app.edit_tend = uieditfield(app.uipanel1, 'text');
            app.edit_tend.Tag = 'edit_tend';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'This script calculates statistics for each time segment.  The segment duration should be much shorter than the total duration.';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'The input must have two columns:  time (sec) & amplitude';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'vibrationdata_tvfa.m  ver 1.4   by Tom Irvine';
            app.uipanel_save = uipanel(app.figure1);
            app.uipanel_save.Title = 'Save Time History Data';
            app.uipanel_save.Tag = 'uipanel_save';
            app.pushbutton_save = uibutton(app.uipanel_save, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Tag = 'pushbutton_save';
            app.pushbutton_save.Text = 'Save';
            app.text15 = uilabel(app.uipanel_save);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Output Array Name';
            app.edit_output_array = uieditfield(app.uipanel_save, 'text');
            app.edit_output_array.Tag = 'edit_output_array';
            app.text16 = uilabel(app.uipanel_save);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Select Function';
            app.listbox_function = uilistbox(app.uipanel_save);
            app.listbox_function.Items = {'Peak     ', 'RMS      ', 'Std Dev  ', 'Mean     ', 'Frequency', 'Kurtosis'};
            app.listbox_function.Tag = 'listbox_function';
            app.listbox_function.Value = 'Peak     ';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_tvfa_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_tvfa_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end