classdef vibrationdata_unit_conversion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        uipanel1                matlab.ui.container.Panel
        edit_results            matlab.ui.control.TextArea
        text6                   matlab.ui.control.Label
        pushbutton_clear_value  matlab.ui.control.Button
        edit_value              matlab.ui.control.EditField
        text5                   matlab.ui.control.Label
        pushbutton_return       matlab.ui.control.Button
        pushbutton_calculate    matlab.ui.control.Button
        listbox_input_unit      matlab.ui.control.ListBox
        text4                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        listbox_dimension       matlab.ui.control.ListBox
        text2                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        nChoice        %
        nChoiceUnits  %
        value          % 
        n_acceleration = 1;
        n_acceleration_PSD=2;
        n_acoustic_impedance =3;
        n_acoustic_pressure =4;
        n_acoustic_pressure_water = 5;
        n_angle = 6;
        n_area = 7;
        n_ami = 8;
        n_dc = 9;
        n_displacement = 10;
        n_energy = 11;
        n_energy_per_mass = 12;
        n_force = 13;
        n_frequency = 14;
        n_impedance = 15;
        n_intensity = 16;
        n_jerk = 17;
        n_kinematic_viscosity=18;
        n_length = 19;
        n_mass = 20;
        n_mmi = 21;
        n_massperlength = 22;
        n_massperarea = 23;
        n_masspervolume = 24;
        n_moment= 25;
        n_power = 26;
        n_power_per_area = 27;
        n_pressure = 28;
        n_speed = 29;
        n_stiffness_plate = 30;
        n_stiffness_rotational = 31;
        n_stiffness_translational = 32;
        n_temperature = 33;
        n_torque= 34;
        n_velocity=35;
        n_volume=36;
        n_wavenumber=37;
        n_work=38;     
        meters_per_foot= 0.3048;

        feet_per_meter = 1/0.3048;
        meters_per_mile = 1609.3;
        mmeters_per_inch = 25.40;
        cmeters_per_inch = 2.540;
        meters_per_inch = 0.02540;
        inches_per_meter = 1/0.02540;
        feet_per_mile = 5280.;
        lbm_per_slug = 32.17;
        slug_per_lbm = 1/32.17;
        lbm_per_kg = 2.205;
        kg_per_lbm = 1/2.205;
        kg_per_slugs = 14.59;
        meterspersecond2_per_G = 9.80665;
        lbf_per_n = 0.2248;
        n_per_lbf = 1/0.2248;
        rad_per_deg = pi/180.;
        deg_per_rad = 180/pi;
        Pa_per_psi = 6894.;      
   
    end
    
    methods (Access = private)
        function accel(app, hObject, eventdata, handles)
            
             xg=0.;
             xfpss=0.;
             xmpss=0.;
             xinpss=0.;
             xmmpss=0.;
            
             cgf = app.lbm_per_slug;
             cgm = app.meterspersecond2_per_G;
             cgin = cgf*12.;
             cgmm = cgm*1000.;
            
                     if( app.nChoiceUnits == 1 )
            			 xg= app.value;
                     end
            
            		 if( app.nChoiceUnits == 2 )
            			 xg= app.value/cgin;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xg= app.value/cgf;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xg= app.value/cgmm;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xg= app.value/cgm;
                     end
            
            		 xinpss=xg*cgin;
            		 xfpss=xg*cgf;
            		 xmmpss=xg*cgmm;
            		 xmpss=xg*cgm;
            
                     if( app.nChoiceUnits == 2 )
            			 xinpss= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xfpss= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xmmpss= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xmpss= app.value;
                     end
            
            ss=sprintf('%12.5g G \n\n %12.5g in/sec^2 \n %12.5g ft/sec^2 \n\n %12.5g mm/sec^2 \n %12.5g m/sec^2',...
                xg,xinpss,xfpss,xmmpss, xmpss );
            
            app.edit_results.Value=ss;
        end
        
        function accel_PSD(app, hObject, eventdata, handles)
            
            % G^2/Hz                 1
            % (m/sec^2)^2/Hz         2
            % m^2/sec^3              3
            % ft/sec^2)^2/Hz         4
            % ft^2/sec^3             5
            % (in/sec^2)^2/Hz        6
            % in^2/sec^3             7
            % G^2/(rad/sec)          8
            % (m/sec^2)^2/(rad/sec)  9
            % (ft/sec^2)^2/(rad/sec) 10
            % (in/sec^2)^2/(rad/sec) 11
            
            tpi=2*pi;
            
            	 xg2_per_Hz=0.;
            	 xm_per_sec2_per_Hz=0.;
            	 xft_per_sec2_per_Hz=0.;
            	 xin_per_sec2_per_Hz=0.;
            	 xg2_per_rad=0.;
            
            	 xm_rad=0.;
            	 xf_rad=0.;
            	 xi_rad=0.;
            
            	 C=9.81^2;
            	 Cft=32.2^2;
            	 Cin=386^2;
            
            	if( app.nChoiceUnits == 1 )
            		xg2_per_Hz= app.value;
                end
            	if( app.nChoiceUnits == 2 || app.nChoiceUnits == 3)
            		xg2_per_Hz= app.value/C;
            	end
            	if( app.nChoiceUnits == 4 || app.nChoiceUnits == 5)
            		xg2_per_Hz= app.value/Cft;
            	end
            	if( app.nChoiceUnits == 6 || app.nChoiceUnits == 7)
            		xg2_per_Hz= app.value/Cin;
            	end
            	if( app.nChoiceUnits == 8)
            		xg2_per_Hz= app.value*tpi;
            	end
            	if( app.nChoiceUnits == 9)
            		xg2_per_Hz= app.value*tpi/C;
            	end
            	if( app.nChoiceUnits == 10)
            		xg2_per_Hz= app.value*tpi/Cft;
            	end
            	if( app.nChoiceUnits == 11)
            		xg2_per_Hz= app.value*tpi/Cin;
            	end
            
            
            	xm_per_sec2_per_Hz=xg2_per_Hz*C;
            	xft_per_sec2_per_Hz=xg2_per_Hz*Cft;
            	xin_per_sec2_per_Hz=xg2_per_Hz*Cin;
            	xg2_per_rad= app.value/tpi;
            
            	xm_rad=xm_per_sec2_per_Hz/tpi ;
            	xf_rad=xft_per_sec2_per_Hz/tpi ;
            	xi_rad=xin_per_sec2_per_Hz/tpi ;
            
            	if( app.nChoiceUnits == 2 )
            		xm_per_sec2_per_Hz= app.value;
            	end
            	if( app.nChoiceUnits == 4 )
            		xft_per_sec2_per_Hz= app.value;
            	end
            	if( app.nChoiceUnits == 6 )
            		xin_per_sec2_per_Hz= app.value;
            	end
            	if( app.nChoiceUnits == 8 )
            		xg2_per_rad= app.value;
            	end
            	if( app.nChoiceUnits == 9 )
            		xm_rad= app.value;
            	end
            	if( app.nChoiceUnits == 10 )
            		xf_rad= app.value;
            	end
            	if( app.nChoiceUnits == 11 )
            		xi_rad= app.value;
                end
            
            ss=sprintf('%12.5g G^2/Hz \n\n %12.5g (m/sec^2)^2/Hz \n %12.5g m^2/sec^3 \n\n %12.5g (ft/sec^2)^2/Hz \n %12.5g ft^2/sec^3  \n\n %12.5g (in/sec^2)^2/Hz \n %12.5g in^2/sec^3 \n\n %12.5g G^2/(rad/sec)\n\n %12.5g (m/sec^2)^2/(rad/sec)\n\n %12.5g (ft/sec^2)^2/(rad/sec)\n\n %12.5g (in/sec^2)^2/(rad/sec)',...
                xg2_per_Hz,xm_per_sec2_per_Hz,xm_per_sec2_per_Hz,xft_per_sec2_per_Hz,xft_per_sec2_per_Hz,xin_per_sec2_per_Hz,xin_per_sec2_per_Hz,xg2_per_rad,xm_rad,xf_rad,xi_rad);
            
            app.edit_results.Value=ss;
        end
        
        function acoustic_impedance(app, hObject, eventdata, handles)
            %      Rayls (Pa sec/m)	1
            %	   kg/(m^2 sec)		2
            %	   lbm/(in^2 sec)   3
            %	   slugs/(in^2 sec)	4
            %	   slugs/(ft^2 sec)	5
            %      psi sec/in       6
            
                 xRayls=0.;         %  1 or 2
            	 xlbmpin2sec=0.;    %  3
            	 xslugspin2sec=0.;  %  4
            	 xslugspft2sec=0.;  %  5
            	 xpsisecpin=0.;     %  6


                     if( app.nChoiceUnits == 1 || app.nChoiceUnits == 2 )
            
            			 xRayls= app.value;
                     end
                     if( app.nChoiceUnits == 3 )
            
            			 xRayls= app.value*app.inches_per_meter^2*app.kg_per_lbm;
                     end
                     if( app.nChoiceUnits == 4 )
            
            			 xRayls= app.value*app.inches_per_meter^2*app.kg_per_slugs;
                     end
                     if( app.nChoiceUnits == 5 )
            
            			 xRayls= app.value*app.feet_per_meter^2*app.kg_per_slugs;
                     end
                     if( app.nChoiceUnits == 6 )
            
            			 xRayls= app.value*app.Pa_per_psi*app.inches_per_meter;
                     end
            
            
            		 xlbmpin2sec=xRayls/(app.inches_per_meter^2*app.kg_per_lbm);
            
            		 xslugspin2sec=xRayls/(app.inches_per_meter^2*app.kg_per_slugs);
            
            		 xslugspft2sec=xRayls/(app.feet_per_meter^2*app.kg_per_slugs);
            
            		 xpsisecpin=xRayls/(app.Pa_per_psi*app.inches_per_meter);
            
            
                     if( app.nChoiceUnits == 1 || app.nChoiceUnits == 2 )
            
            			 xRayls= app.value;
                     end
                     if( app.nChoiceUnits == 3 )
            
            			 xlbmpin2sec= app.value;
                     end
                     if( app.nChoiceUnits == 4 )
            
            			 xslugspin2sec= app.value;
                     end
                     if( app.nChoiceUnits == 5 )
            
            			 xslugspft2sec= app.value;
                     end
                     if( app.nChoiceUnits == 6 )
            
            		     xpsisecpin= app.value;
            		 end
            
            ss=sprintf('%12.5g Rayls (Pa sec/m) \n %12.5g kg/(m^2 sec) \n\n %12.5g lbm/(in^2 sec) \n\n %12.5g slugs/(in^2 sec) \n %12.5g slugs/(ft^2 sec)\n\n  %12.5g psi sec/in',xRayls,xRayls,xlbmpin2sec,xslugspin2sec,xslugspft2sec,xpsisecpin);
            app.edit_results.Value=ss;
        end
        
        function acoustics(app, hObject, eventdata, handles)
            
            xdB=0.;          %  1
            xpsirms=0.;      %  2
            xpsfrms=0.;      %  3
            xparms=0.;       %  4
            xmicroparms=0.;  %  5
            xmicrobar=0.;	 %  6
            
            ref2 = 20.e-06;
            
            
                     if( app.nChoiceUnits == 1 )
            
            			 xdB= app.value;
            
            			 xparms  = ref2*10^(xdB/20.);
                     end
                     if( app.nChoiceUnits == 2 )
            
            			 xpsirms= app.value;
            
            			 xparms=xpsirms*app.Pa_per_psi;
                     end
            		 if( app.nChoiceUnits == 3 )
            
            			 xpsfrms= app.value;
            
            			 xparms=xpsfrms*47.873;
                     end
            		 if( app.nChoiceUnits == 4 )
            
            			 xparms= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            
            			 xmicroparms= app.value;
            			 xparms=xmicroparms*1.0e-6;
                     end
            		 if( app.nChoiceUnits == 6 )
            
            			 xmicrobar= app.value;
            			 xmicroparms=xmicrobar*1.0e+05;
            			 xparms=xmicroparms/1.0e+06;
                     end
            		 xmicroparms=xparms*1.0e+06;
            		 xpsirms=xparms/app.Pa_per_psi;
            		 xpsfrms=xpsirms*144.;
            		 xdB = 20.*log10(xparms/ref2);
            		 xmicrobar=xmicroparms/1.0e+05;
            
            		 if( app.nChoiceUnits == 1 )
            			 xdB= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xpsirms= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xpsfrms= app.value;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xparms= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xmicroparms= app.value;
            		 end
            		 if( app.nChoiceUnits == 6 )
            			 xmicrobar= app.value;
                     end
            
            ss=sprintf('%12.5g dB ref 20 micro Pa \n\n %12.5g psi rms \n %12.5g psf rms \n\n %12.5g Pa rms \n %12.5g microPa rms \n\n %12.5g microbar rms',...
                                    xdB,xpsirms,xpsfrms,xparms,xmicroparms,xmicrobar );
            app.edit_results.Value=ss;
        end
        
        function ami(app, hObject, eventdata, handles)
            
            xm4=0.;     %  1
            xcm4=0.;    %  2
            xmm4=0.;    %  3
            xin4=0.;    %  4
            xft4=0.;    %  5
            
            	 c_cm4_meters4	=1./100^4;
            	 c_mm4_meters4	=1./1000^4;
            	 c_in4_meters4	=1./app.inches_per_meter^4;
            	 c_ft4_meters4	= app.meters_per_foot^4;
            
                     if( app.nChoiceUnits == 1 )
            			 xm4= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xm4= app.value*c_cm4_meters4;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xm4= app.value*c_mm4_meters4;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xm4= app.value*c_in4_meters4;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xm4= app.value*c_ft4_meters4;
                     end
            
                     xcm4    = xm4/c_cm4_meters4;
            		 xmm4    = xm4/c_mm4_meters4;
            		 xin4    = xm4/c_in4_meters4;
             	     xft4    = xin4/12^4;
            
            
                     if( app.nChoiceUnits == 2 )
            			 xcm4= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xmm4= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xin4= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xft4= app.value;
                     end
            
            
            ss=sprintf('%12.5g meters^4 \n %12.5g cm^4 \n %12.5g mm^4 \n\n %12.5g in^4 \n %12.5g ft^4',...
                                                             xm4,xcm4,xmm4,xin4,xft4);
            
            app.edit_results.Value=ss;
        end
        
        function angle(app, hObject, eventdata, handles)
            
            xdeg=0.;    %  1
            xrad=0.;    %  2
            
            ccc = atan2(0.,-1.)/180.;
            
                     if( app.nChoiceUnits == 1 )
            			 xdeg= app.value;
            			 xrad= app.value*ccc;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xdeg= app.value/ccc;
            			 xrad= app.value;
                     end
            
            ss=sprintf('%12.5g degrees \n %12.5g radians',xdeg,xrad);
            
            app.edit_results.Value=ss;
        end
        
        function area(app, hObject, eventdata, handles)
            
            xm2=0.;     %  1
            xcm2=0.;    %  2
            xmm2=0.;    %  3
            xin2=0.;    %  4
            xft2=0.;    %  5
            xyard2=0.;  %  6
            xmiles2=0.; %  7
            xacre=0.;   %  8
            
            
            	 cfeet_meters= app.meters_per_foot;
            	 cyards_meters= app.meters_per_foot*3.;
            	 cmiles_meters= app.meters_per_mile;
            
            	 c_cm2_meters2	=1./100.^2.;
            	 c_mm2_meters2	=1./1000.^2.;
            	 c_in2_meters2	=1./app.inches_per_meter^2.;
            	 c_ft2_meters2	= app.meters_per_foot^2.;
            	 c_yards2_meters2 = (app.meters_per_foot*3.)^2;
            	 c_miles2_meters2 = app.meters_per_mile^2.;
            	 c_acres_meters2  = 4047.;
            
            
                     if( app.nChoiceUnits == 1 )
            			 xm2= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xm2= app.value*c_cm2_meters2;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xm2= app.value*c_mm2_meters2;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xm2= app.value*c_in2_meters2;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xm2= app.value*c_ft2_meters2;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xm2= app.value*c_yards2_meters2;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xm2= app.value*c_miles2_meters2;
                     end
            
                     if( app.nChoiceUnits == 8 )
            			 xm2= app.value*c_acres_meters2;
                     end
            
                     xcm2    = xm2/c_cm2_meters2;
            		 xmm2    = xm2/c_mm2_meters2;
            		 xin2    = xm2/c_in2_meters2;
             	     xft2    = xm2/c_ft2_meters2;
            		 xyard2  = xm2/c_yards2_meters2;
            		 xmiles2 = xm2/c_miles2_meters2;
                     xacre   = xm2/c_acres_meters2;
            
                     if( app.nChoiceUnits == 1 )
            			 xm2= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xcm2= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xmm2= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xin2= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xft2= app.value;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xyard2= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xmiles2= app.value;
            			 xacre = xmiles2*640.;
                     end
            
                     if( app.nChoiceUnits == 8 )
            			 xacre= app.value;
            			 xmiles2 = xacre/640.;
                     end
            
            
            ss=sprintf('%12.5g meters^2 \n %12.5g cm^2 \n %12.5g mm^2 \n\n %12.5g in^2 \n %12.5g ft^2 \n\n %12.5g yards^2 \n %12.5g miles^2 \n %12.5g acres',...
                                        xm2,xcm2,xmm2,xin2,xft2,xyard2,xmiles2,xacre);
            
            app.edit_results.Value=ss;
        end
        
        function energy(app, hObject, eventdata, handles)
            
            xj=0.;      %  1
            xerg=0.;    %  2
            xftlbf=0.;  %  3
            xwh=0.;     %  4
            xbtu=0.;    %  5
            xkcal=0.;   %  6
            xev=0.;     %  7
            
            
            	 cerg=1.0e+07;
            	 cftlbf=0.7376;
            	 cwh=2.778e-04;
            	 cbtu=9.478e-04;
            	 ckcal=2.388e-04;
            	 cev=1./1.602e-19;
            
            
                     if( app.nChoiceUnits == 1 )
            			 xj= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xj= app.value/cerg;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xj= app.value/cftlbf;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xj= app.value/cwh;
                     end
            
                     if( app.nChoiceUnits == 5 )
            			 xj= app.value/cbtu;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xj= app.value/ckcal;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xj= app.value/cev;
                     end
            
            
            		 xerg=xj*cerg;
            		 xftlbf=xj*cftlbf;
            		 xwh=xj*cwh;
            		 xbtu=xj*cbtu;
            		 xkcal=xj*ckcal;
            		 xev=xj*cev;
            
            		 if( app.nChoiceUnits == 2 )
            			 xerg= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xftlbf= app.value;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xwh= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xbtu= app.value;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xkcal= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xev= app.value;
                     end
            
            ss=sprintf('%12.5g Joules \n %12.5g ergs \n\n %12.5g ft-lbf \n %12.5g Watt-hours \n %12.5g BTU \n\n %12.5g kilocalories \n %12.5g eV',...
                xj,xerg,xftlbf,xwh,xbtu,xkcal,xev );
            
            app.edit_results.Value=ss;
        end
        
        function energy_per_mass(app, hObject, eventdata, handles)
            
            xj_per_kg=0.;		 %  0
            xkj_per_kg=0.;		 %  1
            xft_lbf_per_lbm=0.;  %  2
            xin_lbf_per_lbm=0.;  %  3
            
            
                 ft_lbf_per_J =  0.7376;
            
                     if( app.nChoiceUnits == 1 )
            			 xj_per_kg= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xj_per_kg= app.value*1000.;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xj_per_kg= app.value*app.lbm_per_kg/ft_lbf_per_J;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xj_per_kg= app.value*app.lbm_per_kg/(12*ft_lbf_per_J);
                     end
            
            
            		 xkj_per_kg=xj_per_kg/1000.;
            		 xft_lbf_per_lbm=xj_per_kg*ft_lbf_per_J/app.lbm_per_kg;
                     xin_lbf_per_lbm=xft_lbf_per_lbm*12;
            
            
            		 if( app.nChoiceUnits == 2 )
            			 xkj_per_kg= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xft_lbf_per_lbm= app.value;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xin_lbf_per_lbm= app.value;
                     end
            
            
            ss=sprintf('%12.5g J/kg \n %12.5g kJ/kg \n\n %12.5g ft-lbf/lbm \n %12.5g in-lbf/lbm',...
                                xj_per_kg,xkj_per_kg,xft_lbf_per_lbm,xin_lbf_per_lbm );
            
            app.edit_results.Value=ss;
        end
        
        function force(app, hObject, eventdata, handles)
            
             xf=0.;
             xn=0.;
             xk=0.;
             xd=0.;
            
             cfn = app.lbf_per_n;
             cfk = 1000.;
             cfd = cfn/0.10e+06;
            
                    if( app.nChoiceUnits == 1 )
            			 xf= app.value;
                    end
            
                    if( app.nChoiceUnits == 2 )
            			 xf= app.value*cfk;
                    end
            
                    if( app.nChoiceUnits == 3 )
            			 xf= app.value*cfn;
                    end
            
                    if( app.nChoiceUnits == 4 )
            			 xf= app.value*cfd;
                    end
            
            		 xk=xf/cfk;
            		 xn=xf/cfn;
            		 xd=xf/cfd;
            
                    if( app.nChoiceUnits == 2 )
            			 xk= app.value;
                    end
            
                    if( app.nChoiceUnits == 3 )
            			 xn= app.value;
                    end
            
                    if( app.nChoiceUnits == 4 )
            			 xd= app.value;
                    end
            
            ss=sprintf('%12.5g lbf \n %12.5g kips \n %12.5g N \n %12.5g dynes',xf,xk,xn,xd);
            
            app.edit_results.Value=ss;
        end
        
        function frequency(app, hObject, eventdata, handles)
            
            
            xf=0.;       % 1
            xrad=0.;     % 2
            xrpm=0.;     % 3
            xper = 0.;   % 4
            
            	 cfrad=2*pi;
            	 cfrpm=60.;
            
                    if( app.nChoiceUnits == 1 )
            			 xf= app.value;
                    end
            
                    if( app.nChoiceUnits == 2 )
            			 xf= app.value/cfrad;
                    end
            
            	    if( app.nChoiceUnits == 3 )
            			 xf= app.value/cfrpm;
                    end
            
            	    if( app.nChoiceUnits == 4 )
            			 xf=1./app.value;
                    end
            
            		 xrad=xf*cfrad;
            		 xrpm=xf*cfrpm;
                     xper = 1./xf;
            
            		 if( app.nChoiceUnits == 2 )
            			 xrad= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xrpm= app.value;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xper= app.value;
                     end
            
            ss=sprintf('Frequency \n\n %12.5g Hz \n %12.5g rad/sec \n %12.5g rpm \n\nPeriod \n\n %12.5g sec',...
                xf,xrad,xrpm,xper );
            
            app.edit_results.Value=ss;
        end
        
        function impedance(app, hObject, eventdata, handles)
            
            
            %		N sec/m				0
            %	    lbf sec/in			1
            %	    lbf sec/ft			2
            
            xNsecpm=0.;		%  1
            xNsecpcm=0.;	%  2
            xlbfsecpin=0.;  %  3
            xlbfsecpft=0.;	%  4
            
            
                     if( app.nChoiceUnits == 1 )
            			 xNsecpm= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xNsecpm= app.value*100.;
                     end
                     if( app.nChoiceUnits == 3 )
            			 xNsecpm= app.value*app.n_per_lbf/app.meters_per_inch;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xNsecpm= app.value*app.n_per_lbf/app.meters_per_foot;
                     end
            
            		 xNsecpcm=xNsecpm/100.;
            		 xlbfsecpin=(xNsecpm/app.n_per_lbf)*app.meters_per_inch;
            		 xlbfsecpft=xlbfsecpin*12.;
            
                     if( app.nChoiceUnits == 2 )
            			 xNsecpcm= app.value;
                     end
                     if( app.nChoiceUnits == 3 )
            			 xlbfsecpin= app.value;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xlbfsecpft= app.value;
                     end
            
            
            ss=sprintf('%12.5g N sec/m \n %12.5g N sec/cm \n\n %12.5g lbf sec/in \n %12.5g lbf sec/ft',...
                                               xNsecpm,xNsecpcm,xlbfsecpin,xlbfsecpft);
            
            app.edit_results.Value=ss;
        end
        
        function intensity(app, hObject, eventdata, handles)
            
            xdB=0.;       % 1
            xw_per_m2=0.; % 2
            
                     if( app.nChoiceUnits == 1 )
            			 xw_per_m2=1.0e-12*10^(app.value/10.);
                     end
            
            		 if( app.nChoiceUnits == 2 )
            			 xw_per_m2= app.value;
                     end
            
            		 xdB=10*log10(xw_per_m2/1.0e-12);
            
            		 if( app.nChoiceUnits == 1 )
            			 xdB= app.value;
                     end
            		 if( app.nChoiceUnits == 2 )
            			 xw_per_m2= app.value;
                     end
            
            
            ss=sprintf('%12.5g dB  ref: 1.0e-12 W/m^2 \n\n %12.5g W/m^2',xdB,xw_per_m2);
            
            app.edit_results.Value=ss;
        end
        
        function jerk(app, hObject, eventdata, handles)
            
            xg=0.;
            xfpss=0.;
            xmpss=0.;
            xinpss=0.;
            xmmpss=0.;
            
             cgf = app.lbm_per_slug;
             cgm = app.meterspersecond2_per_G;
             cgin = cgf*12.;
             cgmm = cgm*1000.;
            
                     if( app.nChoiceUnits == 1 )
            			 xg= app.value;
                     end
            		 if( app.nChoiceUnits == 2 )
            			 xg= app.value/cgin;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xg= app.value/cgf;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xg= app.value/cgmm;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xg= app.value/cgm;
                     end
            
            		 xinpss=xg*cgin;
            		 xfpss=xg*cgf;
            		 xmmpss=xg*cgmm;
            		 xmpss=xg*cgm;
            
                     if( app.nChoiceUnits == 2 )
            			 xinpss= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xfpss= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xmmpss= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xmpss= app.value;
                     end
            
            
            ss=sprintf('%12.5g G/s \n\n %12.5g in/sec^3 \n %12.5g ft/sec^3 \n\n %12.5g mm/sec^3 \n %12.5g m/sec^3',...
                                                       xg,xinpss,xfpss,xmmpss, xmpss );
            
            app.edit_results.Value=ss;
        end
        
        function kinematic_viscosity(app, hObject, eventdata, handles)
            
            xm2=0.;     %  1
            xcm2=0.;    %  2
            xmm2=0.;    %  3
            xin2=0.;    %  4
            xft2=0.;    %  5
            xyard2=0.;  %  6
            xmiles2=0.; %  7
            xacre=0.;   %  8
            
            cfeet_meters= app.meters_per_foot;
            cyards_meters= app.meters_per_foot*3.;
            cmiles_meters= app.meters_per_mile;
            
            	 c_cm2_meters2	=1./100.^2.;
            	 c_mm2_meters2	=1./1000.^2.;
            	 c_in2_meters2	=1./app.inches_per_meter^2.;
            	 c_ft2_meters2	= app.meters_per_foot^2.;
            	 c_yards2_meters2	= (app.meters_per_foot*3.)^2;
            	 c_miles2_meters2	= app.meters_per_mile^2.;
            	 c_acres_meters2	= 4047.;
            
                     if( app.nChoiceUnits == 1 )
            			 xm2= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xm2= app.value*c_cm2_meters2;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xm2= app.value*c_mm2_meters2;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xm2= app.value*c_in2_meters2;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xm2= app.value*c_ft2_meters2;
                     end
            
                     xcm2    = xm2/c_cm2_meters2;
            		 xmm2    = xm2/c_mm2_meters2;
            		 xin2    = xm2/c_in2_meters2;
             	     xft2    = xm2/c_ft2_meters2;
            
                     if( app.nChoiceUnits == 1 )
            			 xm2= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xcm2= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xmm2= app.value;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xin2= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xft2= app.value;
                     end
            
            ss=sprintf('%12.5g meters^2/sec \n %12.5g cm^2/sec \n %12.5g mm^2/sec \n\n %12.5g in^2/sec \n %12.5g ft^2/sec',...
                                                              xm2,xcm2,xmm2,xin2,xft2);
            
            app.edit_results.Value=ss;
        end
        
        function length(app, hObject, eventdata, handles)
            
            xmils=0.;      %  1
            xinches=0.;    %  2
            xfeet=0.;      %  3
            xyards=0.;     %  4
            xmiles=0.;     %  5
            xnautical=0.;  %  6
            xmicrons=0.;   %  7
            xmm=0.;        %  8
            xcm=0.;        %  9
            xmeters=0.;    %  10
            xkm=0.;        % 11
            xau=0.;        % 12
            xly=0.;        % 13
            xparsec=0.;    % 14
            
            
            	 cmils_meters=1./39370.;
            	 cinches_meters= app.meters_per_inch;
            	 cyards_meters=(app.meters_per_foot)*3.;
            	 cmiles_meters= app.meters_per_mile;
            	 cnautical_meters=1852.;
            	 cmicrons_meters=1./1000000.;
            	 cmm_meters=1./1000.;
            	 ccm_meters=1./100.;
            	 ckm_meters=1000.;
            	 cau_meters=1.495980e+11;
            	 cly_meters=9.460550e+15;
            	 cparsec_meters=3.084e+16;
            
            
                     if( app.nChoiceUnits == 1 )
            			 xmeters= app.value*cmils_meters;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xmeters= app.value*cinches_meters;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xmeters= app.value*app.meters_per_foot;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xmeters= app.value*cyards_meters;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xmeters= app.value*cmiles_meters;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xmeters= app.value*cnautical_meters;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xmeters= app.value*cmicrons_meters;
                     end
            
                     if( app.nChoiceUnits == 8 )
            			 xmeters= app.value*cmm_meters;
                     end
            
            		 if( app.nChoiceUnits == 9 )
            			 xmeters= app.value*ccm_meters;
                     end
            		 if( app.nChoiceUnits == 10 )
            			 xmeters= app.value;
                     end
            		 if( app.nChoiceUnits == 11 )
            			 xmeters= app.value*ckm_meters;
                     end
            		 if( app.nChoiceUnits == 12)
            			 xmeters = 	 app.value*cau_meters;
            		 end
            
            		 if( app.nChoiceUnits == 13 )
            			 xmeters= app.value*cly_meters;
                     end
            
            		 if( app.nChoiceUnits == 14)
            			 xmeters = 	 app.value*cparsec_meters;
            		 end
            
                     xmils     = xmeters/cmils_meters;
            		 xinches   = xmeters/app.meters_per_inch;
            		 xfeet     = xmeters/app.meters_per_foot;
             	     xyards    = xmeters/cyards_meters;
            		 xmiles    = xmeters/cmiles_meters;
            		 xnautical = xmeters/cnautical_meters;
            
            		 xmicrons=xmeters/cmicrons_meters;
            		 xmm=xmeters/cmm_meters;
            		 xcm=xmeters/ccm_meters;
            		 xkm=xmeters/ckm_meters;
                     xau=xmeters/cau_meters;
                     xly=xmeters/cly_meters;
            		 xparsec=xmeters/cparsec_meters;
            
                     if( app.nChoiceUnits == 1 )
            			 xmils= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xinches= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xfeet= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xyards= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xmiles= app.value;
            			 xfeet=xmiles*feet_per_mile;
            			 xyards=xfeet/3.;
            			 xinches=xfeet*12.;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xnautical= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xmicrons= app.value;
                     end
            
                     if( app.nChoiceUnits == 8 )
            			 xmm= app.value;
                     end
            
            		 if( app.nChoiceUnits == 9 )
            			 xcm= app.value;
                     end
            
                     if( app.nChoiceUnits == 10 )
            			 xmeters= app.value;
                     end
            
            		 if( app.nChoiceUnits == 11 )
            			 xkm= app.value;
                     end
            
            		 if( app.nChoiceUnits == 12)
            			 xau= app.value;
            		 end
            
            	     if( app.nChoiceUnits == 13 )
            			 xly= app.value;
                     end
            
            		 if( app.nChoiceUnits == 14)
            			 xparsec= app.value;
            		 end
            
            
            ss=sprintf('%12.5g Mils \n %12.5g inches \n %12.5g feet \n %12.5g yards \n\n %12.5g miles (statute) \n %12.5g nautical miles \n\n %12.5g microns \n %12.5g mm \n %12.5g cm \n\n %12.5g meters \n %12.5g km \n\n %12.5g AU \n %12.5g light-years \n %12.5g parsec',...
            			     xmils,xinches,xfeet,xyards,xmiles,xnautical,xmicrons,xmm,xcm,xmeters,xkm,xau,xly,xparsec);
            
            app.edit_results.Value=ss;
        end
        
        function mass(app, hObject, eventdata, handles)
            
            xlbm=0.;          %  1
            xslugs=0.;        %  2
            xlbfs2perin = 0.; % 3
            xounces=0.;       %  4
            xkg=0.;           %  5
            xg=0.;            %  6
            xmg=0.;           %  7
            xgrains=0.;       %  8
            
            	 conv_slug = app.lbm_per_slug;
            	 conv_ounces = 1./16.;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbm= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xlbm= app.value;
            			 xlbm=xlbm*conv_slug;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xlbm= app.value;
            			 xlbm=xlbm*conv_slug*12.;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xlbm= app.value;
            			 xlbm=xlbm*conv_ounces;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xlbm= app.value;
            			 xlbm=xlbm*app.lbm_per_kg;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xlbm= app.value;
            			 xlbm=xlbm*app.lbm_per_kg/1000.;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xlbm= app.value;
            			 xlbm=xlbm*app.lbm_per_kg/(1000.^2);
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xlbm= app.value;
            			 xlbm=xlbm*app.lbm_per_kg*64.79891/(1000.^2);
                     end
            
            		 xslugs = xlbm/conv_slug;
            		 xlbfs2perin = xslugs/12.;
            		 xounces = xlbm/conv_ounces;
            		 xkg    = xlbm/app.lbm_per_kg;
            		 xg     = xkg*1000.;
            		 xmg    = xg*1000.;
            		 xgrains = xmg/64.79891;
            
                     if( app.nChoiceUnits == 2 )
            			 xslugs = app.value;
                     end
            
            		 if( app.nChoiceUnits == 3)
            			 xlbfs2perin = app.value;
            
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xounces= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xkg= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xg= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xmg= app.value;
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xgrains= app.value;
                     end
            
            ss=sprintf(' %12.5g lbm \n %12.5g slugs (lbf sec^2/ft) \n %12.5g lbf sec^2/in \n\n %12.5g ounces \n\n %12.5g kg \n %12.5g grams \n %12.5g mg \n\n %12.5g grains	',...
                xlbm,xslugs,xlbfs2perin,xounces,xkg,xg,xmg,xgrains );
            
            
            app.edit_results.Value=ss;
        end
        
        function massperarea(app, hObject, eventdata, handles)
            
            xlbmperft2=0.;       %  1
            xlbmperin2=0.;       %  2
            xslugsperft2=0.;     %  3
            xslugsperin2=0.;     %  4
            xlbfsec2perin3 = 0.; %  5
            xkgperm2=0.;         %  6
            
            in2perft2 = 12.^2.;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbmperft2= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xlbmperin2= app.value;
            		     xlbmperft2=xlbmperin2*in2perft2;
                     end
            
                     if( app.nChoiceUnits == 3 )
            			  xlbmperft2= app.value*32.2;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xlbmperin2= app.value*32.2;
            	         xlbmperft2=xlbmperin2*in2perft2;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xlbfsec2perin3= app.value;
            
            %           lbf = slug ft/sec^2
            %           lbfsec2perin4 = (slug ft/sec^2 )sec2perin4
            %           lbfsec2perin4 = (slug ft/in4)
            
            			  in_per_ft = 12.;
            
            			  slugftperin3 = xlbfsec2perin3;
            			  lbmftperin3 = slugftperin3*app.lbm_per_slug;
            			  lbmperin2 = lbmftperin3*in_per_ft;
            
            			 xlbmperft2=lbmperin2*in2perft2;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xkgperm2= app.value;
            			 xlbmperft2=xkgperm2*app.lbm_per_kg*app.meters_per_foot^2.;
                     end
            
            		 in_per_ft=12.;
            
            		 xlbmperin2 = xlbmperft2/in2perft2;
            		 xlbfsec2perin3=xlbmperft2/(in2perft2*in_per_ft*app.lbm_per_slug);
            		 xkgperm2=xlbmperft2/( app.lbm_per_kg*app.meters_per_foot^2.);
            		 xslugsperft2=xlbmperft2/32.2;
            	     xslugsperin2=xlbmperin2/32.2;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbmperft2= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xlbmperin2= app.value;
                     end
            
                     if( app.nChoiceUnits == 3 )
            			 xslugsperft2= app.value;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xslugsperin2= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xlbfsec2perin3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xkgperm2= app.value;
                     end
            
            ss=sprintf(' %12.5g lbm/ft^2 \n %12.5g lbm/in^2 \n\n %12.5g slugs/ft^2 \n %12.5g slugs/in^2 \n\n %12.5g lbf sec^2/in^3 \n\n %12.5g kg/m^2 \n\n',...
                 xlbmperft2,xlbmperin2,xslugsperft2,xslugsperin2,xlbfsec2perin3,xkgperm2);
            
            app.edit_results.Value=ss;
        end
        
        function massperlength(app, hObject, eventdata, handles)
            
            
            xkgperm=0.;         %  1
            xlbmperin=0.;       %  2
            xlbmperft=0.;       %  3
            xlbfsec2perin2=0.;  %  4
            
            
            	if( app.nChoiceUnits == 1 )
            			xkgperm= app.value;
            	end
            
            	if( app.nChoiceUnits == 2 )
            
            			xkgperm= app.value*app.kg_per_lbm*app.inches_per_meter;
            	end
            
            	if( app.nChoiceUnits == 3 )
            
            			xkgperm= app.value*app.kg_per_lbm*app.feet_per_meter;
            	end
            
            	if( app.nChoiceUnits == 4 )
            			xkgperm= app.value*app.n_per_lbf*(app.inches_per_meter^2);
            	end
            
            
            	xlbmperin=xkgperm/(app.kg_per_lbm*app.inches_per_meter);
            	xlbmperft=xlbmperin*12.;
            	xlbfsec2perin2=xkgperm/(n_per_lbf*app.inches_per_meter^2);
            
            	if( app.nChoiceUnits == 2 )
            			 xlbmperin= app.value;
                end
            	if( app.nChoiceUnits == 3 )
            			 xlbmperft= app.value;
                end
            	if( app.nChoiceUnits == 4 )
            			 xlbfsec2perin2= app.value;
            	end
            
            ss=sprintf(' %12.5g kg/m \n\n %12.5g lbm/in \n %12.5g lbm/ft \n\n %12.5g lbf sec^2/in^2',...
                xkgperm,xlbmperin,xlbmperft,xlbfsec2perin2);
            
            app.edit_results.Value=ss;
        end
        
        function masspervolume(app, hObject, eventdata, handles)
            
            xlbmperft3=0.;       %  1
            xlbmperin3=0.;       %  2
            xslugsperft3=0.;     %  3
            xslugsperin3=0.;     %  4
            xlbfsec2perin4 = 0.; %  5
            xkgperm3=0.;         %  6
            xgpercm3=0.;         %  7
            xsg=0.;              %  8
            
            in3perft3 = 12^3;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbmperft3= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xlbmperin3= app.value;
            		     xlbmperft3=xlbmperin3*in3perft3;
                     end
            
                     if( app.nChoiceUnits == 3 )
            			  xlbmperft3= app.value*32.2;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xlbmperin3= app.value*32.2;
            	         xlbmperft3=xlbmperin3*in3perft3;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            
            			 xlbfsec2perin4= app.value;
            
            %           lbf = slug ft/sec^2
            %           lbfsec2perin4 = (slug ft/sec^2 )sec2perin4
            %           lbfsec2perin4 = (slug ft/in4)
            
            			  in_per_ft = 12.;
            
            			  slugftperin4 = xlbfsec2perin4;
            			  lbmftperin4 = slugftperin4*app.lbm_per_slug;
            			  lbmperin3 = lbmftperin4*in_per_ft;
            			 xlbmperft3=lbmperin3*in3perft3;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xkgperm3= app.value;
            			 xlbmperft3=xkgperm3*app.lbm_per_kg*app.meters_per_foot^3.;
            
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xgpercm3= app.value;
                         xkgperm3 = (xgpercm3 /1000.)*100^3;
            			 xlbmperft3=xkgperm3*app.lbm_per_kg*app.meters_per_foot^3;
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xsg= app.value;
            			 xgpercm3 = xsg;
                         xkgperm3 = (xgpercm3 /1000.)*100^3;
            			 xlbmperft3=xkgperm3*app.lbm_per_kg*app.meters_per_foot^3;
                     end
            
            		  in_per_ft=12.;
            
            		 xlbmperin3 = xlbmperft3/in3perft3;
            		 xlbfsec2perin4=xlbmperft3/(in3perft3*in_per_ft*app.lbm_per_slug);
            		 xkgperm3=xlbmperft3/( app.lbm_per_kg*app.meters_per_foot^3 );
                     xgpercm3=xkgperm3*1000./100^3;
            		 xsg=xgpercm3;
            		 xslugsperft3=xlbmperft3/32.2;
            	     xslugsperin3=xlbmperin3/32.2;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbmperft3= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xlbmperin3= app.value;
                     end
                     if( app.nChoiceUnits == 3 )
            			 xslugsperft3= app.value;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xslugsperin3= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xlbfsec2perin4= app.value;
                     end
            		 if( app.nChoiceUnits == 6 )
            			 xkgperm3= app.value;
                     end
            		 if( app.nChoiceUnits == 7 )
            			 xgpercm3= app.value;
                         xsg = xgpercm3;
                     end
            		 if( app.nChoiceUnits == 8 )
            			 xsg= app.value;
            		     xgpercm3 = xsg;
                     end
            
            ss=sprintf(' %12.5g lbm/ft^3 \n %12.5g lbm/in^3 \n\n %12.5g slugs/ft^3 \n %12.5g slugs/in^3 \n\n %12.5g lbf sec^2/in^4 \n\n %12.5g kg/m^3 \n\n %12.5g g/cm^3 \n\n %12.5g specific gravity : H20',...
                xlbmperft3,xlbmperin3,xslugsperft3,xslugsperin3,xlbfsec2perin4,xkgperm3,xgpercm3,xsg );
            
            app.edit_results.Value=ss;
        end
        
        function mmi(app, hObject, eventdata, handles)
            
            	 xlbmft2=0.;         %  1
            	 xlbmin2=0.;         %  2
            	 xslugsft2=0.;       %  3
            	 xslugsin2=0.;       %  4
            	 xlbfsec2in=0.;      %  5
                 xkgm2=0.;           %  6
            
            	 conv_slug = app.lbm_per_slug;
            
                     if( app.nChoiceUnits == 1 )
            			 xlbmft2= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xlbmft2= app.value;
            			 xlbmft2=xlbmft2/144.;
                     end
            
                     if( app.nChoiceUnits == 3 )
            			 xlbmft2= app.value;
            			 xlbmft2=xlbmft2*conv_slug;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xlbmft2= app.value;
            			 xlbmft2=xlbmft2*conv_slug/144.;
                     end
            
                     if( app.nChoiceUnits == 5 )
            			 xlbmft2= app.value;
            			 xlbmft2=xlbmft2*conv_slug/12.;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xlbmft2= app.value;
            			 xlbmft2=xlbmft2*app.lbm_per_kg;
            			 xlbmft2=xlbmft2/(app.meters_per_foot^2.);
                     end
            
            		 xlbmin2   = xlbmft2*144.;
            		 xslugsft2 = xlbmft2/conv_slug;
            		 xslugsin2 = xslugsft2*144.;
            		 xlbfsec2in = xlbmft2*12./conv_slug;
            		 xkgm2    = (xlbmft2/app.lbm_per_kg)*(app.meters_per_foot^2.);
            
                     if( app.nChoiceUnits == 2 )
            			 xlbmin2 = app.value;
                     end
            
            		 if( app.nChoiceUnits == 3)
            			 xslugsft2 = app.value;
            		 end
            
            		 if( app.nChoiceUnits == 4 )
            			 xslugsin2= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xlbfsec2in= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xkgm2= app.value;
                     end
            
            ss=sprintf(' %12.5g lbm ft^2 \n %12.5g lbm in^2 \n\n %12.5g slugs ft^2 \n %12.5g slugs in^2 \n\n %12.5g lbf sec^2 in \n\n %12.5g kg m^2 \n',...
                                xlbmft2,xlbmin2,xslugsft2,xslugsin2,xlbfsec2in,xkgm2);
            
            app.edit_results.Value=ss;
        end
        
        function moment(app, hObject, eventdata, handles)
            
            xNm = 0.;
            xftlbf = 0.;
            xinlbf= 0.;
            
                if( app.nChoiceUnits == 1 )
            
            			 xNm= app.value;
                end
                if( app.nChoiceUnits == 2 )
            
            			 xNm= app.value*app.n_per_lbf*app.meters_per_foot;
                end
            
                if( app.nChoiceUnits == 3 )
            
            			 xNm= app.value*app.n_per_lbf*app.meters_per_inch;
                end
            
            	xftlbf = xNm/(n_per_lbf*app.meters_per_foot);
                xinlbf = xNm/(n_per_lbf*app.meters_per_inch);
            
            
            	if( app.nChoiceUnits == 2 )
            			 xftlbf= app.value;
                end
            
                if( app.nChoiceUnits == 3 )
            			 xinlbf= app.value;
                end
            
            
            ss=sprintf('%12.5g N m\n %12.5g ft lbf\n %12.5g in lbf\n',xNm,xftlbf,xinlbf);
            
            app.edit_results.Value=ss;
        end
        
        function power(app, hObject, eventdata, handles)
            
            xw=0.;            % 1
            xkw=0.;           % 2
            xftlbfpersec=0.;  % 3
            xhorse=0.;        % 4
            xbtuperh=0.;      % 5
            xdBm=0.;          % 6
            xdBp=0.;          % 7
            
            	 ckw=1./1000.;
            	 cftlbfpersec=0.7376;
            	 chorse=1.341e-03;
            	 cbtuperh=3.412;
            
                     if( app.nChoiceUnits == 1 )
            			 xw= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xw= app.value/ckw;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xw= app.value/cftlbfpersec;
                     end
            
                     if( app.nChoiceUnits == 4 )
            			 xw= app.value/chorse;
                     end
            
                     if( app.nChoiceUnits == 5 )
            			 xw= app.value/cbtuperh;
                     end
            
                     if( app.nChoiceUnits == 6 )
            			 xw=0.001*10^(app.value/10.);
                     end
            
                     if( app.nChoiceUnits == 7 )
            			 xw=1.0e-12*10^(app.value/10.);
                     end
            
            
            		 xkw=xw*ckw;
            		 xftlbfpersec=xw*cftlbfpersec;
            		 xhorse=xw*chorse;
            		 xbtuperh=xw*cbtuperh;
            
            		 xdBm=10*log10(xw/0.001);
            		 xdBp=10*log10(xw/1.0e-12);
            
            		 if( app.nChoiceUnits == 2 )
            			 xkw= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xftlbfpersec= app.value;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xhorse= app.value;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xbtuperh= app.value;
                     end
                	 if( app.nChoiceUnits == 6 )
            			 xdBm= app.value;
                     end
            		 if( app.nChoiceUnits == 7 )
            			 xdBp= app.value;
                     end
            
            
            ss=sprintf('%12.5g Watts \n %12.5g kilowatts \n\n %12.5g ft-lbf/sec \n %12.5g Horsepower \n\n %12.5g BTU/hour \n\n %12.5g dBm \n %12.5g dB (ref: 1.0e-12 Watt) ',...
                                  xw,xkw,xftlbfpersec,xhorse,xbtuperh,xdBm,xdBp);
            
            app.edit_results.Value=ss;
        end
        
        function pressure(app, hObject, eventdata, handles)
            
            xatm=0.;  % 1
            xpsi=0.;  % 2
            xpsf=0.;  % 3
            xpa=0.;	  % 4
            xdcm=0.;  % 5
            xbar=0.;  % 6
            xmbar=0.; % 7
            xtorr=0.; % 8
            xinhg=0.; % 9
            xinw=0.;  % 10
            
             cpsi=14.70;
             cpsf=2116.;
             cpa=1.013e+05;
             cdcm=1.013e+06;
             cmbar= 1.013e+03;
             cbar=cmbar/1000.;
             ctorr=760.;
             cinhg=76./app.cmeters_per_inch;
             cinw = 406.8;
            
                    if( app.nChoiceUnits == 1 )
            			 xatm= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )		% psi
            			 xatm= app.value/cpsi;
                     end
            
                     if( app.nChoiceUnits == 3 )		% psf
            			 xatm= app.value/cpsf;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xatm= app.value/cpa;	  % pa
            		 end
            		 if( app.nChoiceUnits == 5 )
            			 xatm= app.value/cdcm;	  % dcm
                     end
            		 if( app.nChoiceUnits == 6 )
            			 xatm= app.value/cbar;   % bar
                     end
            		 if( app.nChoiceUnits == 7 )
            			 xatm= app.value/cmbar;   % mbar
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xatm= app.value/ctorr;	  % torr
                     end
            
              	     if( app.nChoiceUnits == 9 )
            			 xatm= app.value/cinhg;	  % in Hg
                     end
            
                     if( app.nChoiceUnits == 10 )
            			 xatm= app.value/cinw;	  % in Water
                     end
            
            		 xpsi  =xatm*cpsi;
             		 xpsf  =xatm*cpsf;
            		 xpa   =xatm*cpa;
            		 xdcm  =xatm*cdcm;
            		 xmbar =xatm*cmbar;
            		 xtorr =xatm*ctorr;
            		 xinhg =xatm*cinhg;
            		 xinw  =xatm*cinw;
            		 xbar  =xmbar/1000.;
            
                     if( app.nChoiceUnits == 2 )
            			 xpsi= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xpsf= app.value;
                     else
            			 xpsf = 144.*xpsi;
            		 end
            
                     if( app.nChoiceUnits == 4 )
            			 xpa= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xdcm= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xbar= app.value;
            		 end
            
            		 if( app.nChoiceUnits == 7 )
            			 xmbar= app.value;
            		 end
            
            		 if( app.nChoiceUnits == 8 )
            			 xtorr= app.value;
            		 end
            
                     if( app.nChoiceUnits == 9 )
            			 xinhg= app.value;
            		 end
            
                     if( app.nChoiceUnits == 10 )
            			 xinw= app.value;
                     end
            
            ss=sprintf('%12.5g atm \n %12.5g psi \n %12.5g psf \n\n %12.5g Pascal \n %12.5g dynes/cm^2 \n\n %12.5g bar \n %12.5g millibar \n\n %12.5g mm Hg (torr) \n %12.5g inches Hg \n %12.5g inches Water\n\n',...
                xatm,xpsi,xpsf,xpa,xdcm,xbar,xmbar,xtorr,xinhg,xinw );
            
            app.edit_results.Value=ss;
        end
        
        function stiffness_plate(app, hObject, eventdata, handles)
            
             xNm = 0.;
             xftlbf = 0.;
             xinlbf= 0.;
             xlbmin2_per_sec2=0.;
            
                if( app.nChoiceUnits == 1 )
            
            			 xNm= app.value;
                end
                if( app.nChoiceUnits == 2 )
            
            			 xNm= app.value*app.n_per_lbf*app.meters_per_foot;
                end
                if( app.nChoiceUnits == 3 )
            
            			 xNm= app.value*app.n_per_lbf*app.meters_per_inch;
                end
                if( app.nChoiceUnits == 4 )
            
            			 xNm= app.value*app.n_per_lbf*app.meters_per_inch/386.;
                end
            
            
            	xftlbf = xNm/(n_per_lbf*app.meters_per_foot);
                xinlbf = xNm/(n_per_lbf*app.meters_per_inch);
            
            
            	xlbmin2_per_sec2=xinlbf*386.;
            
            	if( app.nChoiceUnits == 2 )
            
            			 xftlbf= app.value;
                end
                if( app.nChoiceUnits == 3 )
            
            			 xinlbf= app.value;
                end
                if( app.nChoiceUnits == 4 )
            
            			 xlbmin2_per_sec2= app.value;
                end
            
            
            ss=sprintf('%12.5g N m\n %12.5g lbf ft\n %12.5g lbf in \n\n %12.5g lbm in^2/sec^2 ',...
                                                  xNm,xftlbf,xinlbf,xlbmin2_per_sec2);
            
            app.edit_results.Value=ss;
        end
        
        function stiffness_rotational(app, hObject, eventdata, handles)
            
                xNmperrad = 0.;
                xftlbfperrad = 0.;
                xinlbfperrad = 0.;
                xNmperdeg = 0.;
                xftlbfperdeg = 0.;
                xinlbfperdeg = 0.;
            
                if( app.nChoiceUnits == 1 )
            			 xNmperrad= app.value;
                end
                if( app.nChoiceUnits == 2 )
            			 xNmperrad= app.value*app.n_per_lbf*app.meters_per_foot;
                end
                if( app.nChoiceUnits == 3 )
            			 xNmperrad= app.value*app.n_per_lbf*app.meters_per_inch;
                end
                if( app.nChoiceUnits == 4 )
            			 xNmperrad= app.value*deg_per_rad;
                end
            	if( app.nChoiceUnits == 5 )
            			 xNmperrad= app.value*app.n_per_lbf*app.meters_per_foot*app.deg_per_rad;
                end
                if( app.nChoiceUnits == 6 )
            			 xNmperrad= app.value*app.n_per_lbf*app.meters_per_inch*app.deg_per_rad;
                end
            
            	xftlbfperrad = xNmperrad/(n_per_lbf*app.meters_per_foot);
                xinlbfperrad = xNmperrad/(n_per_lbf*app.meters_per_inch);
                xNmperdeg    = xNmperrad/(app.deg_per_rad);
                xftlbfperdeg = xNmperrad/(n_per_lbf*app.meters_per_foot*app.deg_per_rad);
                xinlbfperdeg = xNmperrad/(n_per_lbf*app.meters_per_inch*app.deg_per_rad);
            
            	if( app.nChoiceUnits == 2 )
            			 xftlbfperrad= app.value;
                end
                if( app.nChoiceUnits == 3 )
            			 xinlbfperrad= app.value;
                end
                if( app.nChoiceUnits == 4 )
            			 xNmperdeg= app.value;
            	end
            	if( app.nChoiceUnits == 5 )
            			 xftlbfperdeg= app.value;
                end
                if( app.nChoiceUnits == 6 )
            			 xinlbfperdeg= app.value;
                end
            
            ss=sprintf('%12.5g N m/rad\n %12.5g ft lbf/rad\n %12.5g in lbf/rad\n\n %12.5g N m/deg\n %12.5g ft lbf/deg\n %12.5g in lbf/deg',...
                xNmperrad,xftlbfperrad,xinlbfperrad,xNmperdeg,xftlbfperdeg,xinlbfperdeg);
            
            app.edit_results.Value=ss;
            
            app.edit_results.Value=ss;
        end
        
        function stiffness_translational(app, hObject, eventdata, handles)
            
            xNperm=0.;       %  1
            xNpermm=0.;      %  2
            xlbfperft=0.;    %  3
            xlbfperin=0.;    %  4
            
                     if( app.nChoiceUnits == 1 )
            			 xNperm= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xNperm= app.value*1000;
                     end
                     if( app.nChoiceUnits == 3 )
            			 xNperm= app.value*app.feet_per_meter*app.n_per_lbf;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xNperm= app.value*app.inches_per_meter*app.n_per_lbf;
                     end
            
            	     xNpermm=xNperm/1000.;
            	     xlbfperft=xNperm/(app.feet_per_meter*app.n_per_lbf);
            	     xlbfperin=xNperm/(app.inches_per_meter*app.n_per_lbf);
            
                     if( app.nChoiceUnits == 2 )
            			 xNpermm= app.value;
                     end
                     if( app.nChoiceUnits == 3 )
            			 xlbfperft= app.value;
            			 xlbfperin=xlbfperft/12.;
                     end
                     if( app.nChoiceUnits == 4 )
            			 xlbfperin= app.value;
            			 xlbfperft=xlbfperin*12.;
                     end
            
            ss=sprintf('%12.5g N/m \n %12.5g N/mm \n\n %12.5g lbf/ft \n %12.5g lbf/in  ',...
                                                xNperm, xNpermm, xlbfperft, xlbfperin);
            
            app.edit_results.Value=ss;
        end
        
        function temperature(app, hObject, eventdata, handles)
            
            xf=0.;          %  0
            xc=0.;          %  1
            xr=0.;          %  2
            xk=0.;          %  3
            
            
                     if( app.nChoiceUnits == 1 )
            
            			 xf= app.value;
            			 xc=(xf-32.)*5./9.;
            			 xk=xc+273.15;
            			 xr= xf+459.67;
                     end
                     if( app.nChoiceUnits == 2 )
            
            			 xc= app.value;
            			 xf=xc*(9./5.)+32.;
            			 xk=xc+273.15;
            			 xr= xf+459.67;
                     end
            		 if( app.nChoiceUnits == 3 )
            
            			 xr= app.value;
            			 xf=xr-459.67;
            			 xc=(xf-32.)*5./9.;
            			 xk=xc+273.15;
                     end
            		 if( app.nChoiceUnits == 4 )
            
            			 xk= app.value;
            			 xc=xk-273.15;
            			 xf=xc*(9./5.)+32.;
            			 xr= xf+459.67;
                     end
            
            		 if(xr<1.0e-09)
                         xr=0.;
                     end
            		 if(xk<1.0e-09)
                         xk=0.;
                     end
            
            ss=sprintf('%12.5g deg F \n %12.5g deg C \n %12.5g deg R \n %12.5g K',xf,xc,xr,xk );
            
            app.edit_results.Value=ss;
        end
        
        function velox(app, hObject, eventdata, handles)
            
            
            xinchespersecond    =0.;   % 1
            xfeetpersecond   =0.;      % 2
            xmilesperhour    =0.;      % 3
            xknots = 0.;	            % 4
            xmeterspersecond = 0.;     % 5
            xkmpersecond = 0.;         % 6
            xkmperhour = 0.;           % 7
            xc = 0.;                   % 8
            xmach =0.;                 % 9
            
            	 fm = app.feet_per_mile/3600.;
            	 fk = 6076./3600.;
            
                     if( app.nChoiceUnits == 1 )
            			 xfeetpersecond= app.value/12;
                     end
                     if( app.nChoiceUnits == 2 )
            			 xfeetpersecond= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            			 xfeetpersecond= app.value*fm;
                     end
            		 if( app.nChoiceUnits == 4 )
            			 xfeetpersecond= app.value*fk;
                     end
            		 if( app.nChoiceUnits == 5 )
            			 xfeetpersecond= app.value/app.meters_per_foot;
                     end
            		 if( app.nChoiceUnits == 6 )
            			 xfeetpersecond= app.value*1000./app.meters_per_foot;
                     end
            		 if( app.nChoiceUnits == 7 )
            			 xfeetpersecond= app.value*(1000./app.meters_per_foot)/3600.;
                     end
            		 if( app.nChoiceUnits == 8 )
            			 xfeetpersecond= app.value*3.00e+08/app.meters_per_foot;
                     end
            		 if( app.nChoiceUnits == 9 )
            			 xfeetpersecond= app.value*1125.;
                     end
            
            		 xinchespersecond=xfeetpersecond*12.;
            		 xmilesperhour=xfeetpersecond/fm;
            		 xknots=xfeetpersecond/fk;
            		 xmeterspersecond = xfeetpersecond*app.meters_per_foot;
            		 xkmpersecond=xmeterspersecond/1000.;
            		 xkmperhour=xkmpersecond*3600.;
            		 xc=xmeterspersecond/3.00e+08;
            		 xmach=xfeetpersecond/1125.;
            
                     if( app.nChoiceUnits == 1 )
            			 xinchespersecond= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xfeetpersecond= app.value;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xmilesperhour= app.value;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xknots= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xmeterspersecond= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xkmpersecond= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xkmperhour= app.value;
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xc= app.value;
                     end
            
            		 if( app.nChoiceUnits == 9 )
            			 xmach= app.value;
                     end
            
            ss=sprintf('%12.5g inches/sec \n %12.5g feet/sec \n\n %12.5g miles/hour \n %12.5g knots\n\n %12.5g meter/sec \n %12.5g km/sec \n %12.5g km/hr \n\n %12.5g c (speed of light) \n\n %12.5g Mach (sea level)',...
            			     xinchespersecond,xfeetpersecond,xmilesperhour,xknots,xmeterspersecond, xkmpersecond, xkmperhour, xc, xmach);
            
            
            app.edit_results.Value=ss;
        end
        
        function volume(app, hObject, eventdata, handles)
            
            	 xin3=0.;     %  1
                 xft3=0.;     %  2
                 xmiles3=0.;  %  3
            	 xmm3=0.;     %  4
            	 xcm3=0.;     %  5
            	 xm3 =0.;     %  6
            	 xkm3=0.;     %  7
            	 xliter=0.;   %  8
                 xgallons=0.; %  9
            	 xquarts=0.;  %  10
            	 xpints=0.;   %  11
            
            
            	 cft3=12^3;
            
            	 cmiles3=(12.*app.feet_per_mile)^3;
                 cmm3= app.mmeters_per_inch^3;
            	 ccm3= app.cmeters_per_inch^3;
            
            
            	 cm3= app.meters_per_inch^3;
            
            	 ckm3= (app.meters_per_inch/1000.)^3;
            
            	 cliter=1000./ccm3;
            	 cgallons=231.0;
            	 cquarts=cgallons/4.;


                     if( app.nChoiceUnits == 1 )
            			 xin3= app.value;
                     end
            
                     if( app.nChoiceUnits == 2 )
            			 xin3= app.value*cft3;
                     end
            
            		 if( app.nChoiceUnits == 3 )
            			 xin3= app.value*cmiles3;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xin3= app.value/cmm3;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xin3= app.value/ccm3;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xin3= app.value/cm3;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xin3= app.value/ckm3;
                     end
            		 if( app.nChoiceUnits == 8 )
            			 xin3= app.value*cliter;
                     end
            		 if( app.nChoiceUnits == 9 )
            			 xin3= app.value*cgallons;
                     end
            
            		 if( app.nChoiceUnits == 10 )
            			 xin3= app.value*cquarts;
                     end
            
            		 if( app.nChoiceUnits == 11 )
            			 xin3= app.value*cquarts/2;
                     end
            
            
                     xft3    = xin3/cft3;
            		 xmiles3 = xin3/cmiles3;
            		 xmm3    = xin3*cmm3;
            		 xcm3    = xin3*ccm3;
            		 xm3    =  xin3*cm3;
            		 xkm3    = xin3*ckm3;
            		 xliter    = xin3/cliter;
            		 xgallons  = xin3/cgallons;
            		 xquarts = xin3/cquarts;
            	     xpints = 2*xquarts;
            
            
                     if( app.nChoiceUnits == 1 )
            
            			 xin3= app.value;
                     end
                     if( app.nChoiceUnits == 2 )
            
            			 xft3= app.value;
                     end
            		 if( app.nChoiceUnits == 3 )
            
            			 xmiles3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 4 )
            			 xmm3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 5 )
            			 xcm3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 6 )
            			 xm3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 7 )
            			 xkm3= app.value;
                     end
            
            		 if( app.nChoiceUnits == 8 )
            			 xliter= app.value;
                     end
            
            		 if( app.nChoiceUnits == 9 )
            			 xgallons= app.value;
                     end
            
            		 if( app.nChoiceUnits == 10 )
            			 xquarts= app.value;
                     end
            
            		 if( app.nChoiceUnits == 11 )
            			 xpints= app.value;
                     end
            
            
            ss=sprintf('%12.5g in^3 \n %12.5g ft^3 \n %12.5g miles^3 \n\n %12.5g mm^3 \n %12.5g cm^3 \n %12.5g m^3 \n %12.5g km^3 \n\n %12.5g liters \n %12.5g gallons \n %12.5g quarts \n %12.5g pints',...
            			     xin3,xft3,xmiles3,xmm3,xcm3,xm3,xkm3,xliter,xgallons,xquarts,xpints);
            
            app.edit_results.Value=ss;
        end
        
        function wavenumber(app, hObject, eventdata, handles)
            
            xradpermeter=0.;  % 0
            xradperft=0.;     % 1
            xradperinch=0;    % 2
            
            
            	if( app.nChoiceUnits == 1 )
            		xradpermeter= app.value;
                end
            
                if( app.nChoiceUnits == 2 )
            		xradpermeter= app.value*app.feet_per_meter;
                end
                if( app.nChoiceUnits == 3 )
            		xradpermeter= app.value*app.inches_per_meter;
                end
            
            	xradperft=xradpermeter*app.meters_per_foot;
            	xradperinch=xradperft/12.;
            
                if( app.nChoiceUnits == 2 )
            		xradperft= app.value;
            		xradperinch=xradperft/12.;
                end
                if( app.nChoiceUnits == 3 )
            		xradperinch= app.value;
                end
            
            
            ss=sprintf(' %12.5g rad/m \n\n %12.5g rad/ft \n %12.5g rad/inch',xradpermeter,xradperft,xradperinch);
            
            app.edit_results.Value=ss;
        end
        
        
        function LD(app)

            app.edit_results.Enable='off';
            app.edit_results.Value='';

            Items = char(app.listbox_dimension.Items);
            im=app.listbox_dimension.Value;
            [app.nChoice]=find_index(Items,im);
            
            %%%%%%%%%%%%%%%%%%%
            
            if(app.nChoice == app.n_acoustic_impedance )
            
            		  string_th{1}=sprintf('Rayls (Pa sec/m)');
            	      string_th{2}=sprintf('kg/(m^2 sec)');
            	      string_th{3}=sprintf('lbm/(in^2 sec)');
            	      string_th{4}=sprintf('slugs/(in^2 sec)');
            	      string_th{5}=sprintf('slugs/(ft^2 sec)');
            		  string_th{6}=sprintf('psi sec/in');
            
            end
            if(app.nChoice == app.n_acoustic_pressure || app.nChoice == app.n_acoustic_pressure_water )
            
            		  string_th{1}=sprintf('dB ');
            	      string_th{2}=sprintf('psi rms');
            	      string_th{3}=sprintf('psf rms');
            	      string_th{4}=sprintf('Pa rms');
            	      string_th{5}=sprintf('microPa rms');
            	      string_th{6}=sprintf('microbar rms');
            end
            if(app.nChoice == app.n_displacement || app.nChoice == app.n_length )
            
                    string_th{1}=sprintf('Mils');
                    string_th{2}=sprintf('inches');
                    string_th{3}=sprintf('feet');
                    string_th{4}=sprintf('yards');
                    string_th{5}=sprintf('miles (statute)');
                    string_th{6}=sprintf('nautical miles');
                    string_th{7}=sprintf('microns');
                    string_th{8}=sprintf('millimeters');
                    string_th{9}=sprintf('centimeters');
                    string_th{10}=sprintf('meters');
                    string_th{11}=sprintf('kilometers');
                    string_th{12}=sprintf('Astronomical Units ');
                    string_th{13}=sprintf('light-years');
                    string_th{14}=sprintf('parsec');
            end
            if(app.nChoice == app.n_speed || app.nChoice == app.n_velocity )
            
                      string_th{1}=sprintf('inches/sec ');
            	      string_th{2}=sprintf('feet/sec');
            		  string_th{3}=sprintf('miles/hour');
            		  string_th{4}=sprintf('knots');
            		  string_th{5}=sprintf('meters/sec');
            		  string_th{6}=sprintf('km/sec');
            		  string_th{7}=sprintf('km/hour');
            		  string_th{8}=sprintf('c (speed of light)');
            		  string_th{9}=sprintf('Mach (sea level)');
            end
            if(app.nChoice == app.n_acceleration )
            
                      string_th{1}=sprintf('G ');
            	      string_th{2}=sprintf('in/sec^2');
            		  string_th{3}=sprintf('ft/sec^2');
            		  string_th{4}=sprintf('mm/sec^2');
            		  string_th{5}=sprintf('meter/sec^2');
            end
            if(app.nChoice == app.n_acceleration_PSD )
            
                      string_th{1}=sprintf('G^2/Hz ');
            	      string_th{2}=sprintf('(m/sec^2)^2/Hz');
            		  string_th{3}=sprintf('m^2/sec^3');
            	      string_th{4}=sprintf('(ft/sec^2)^2/Hz');
            		  string_th{5}=sprintf('ft^2/sec^3');
            	      string_th{6}=sprintf('(in/sec^2)^2/Hz');
            		  string_th{7}=sprintf('in^2/sec^3');
                      string_th{8}=sprintf('G^2/(rad/sec) ');
            	      string_th{9}=sprintf('(m/sec^2)^2/(rad/sec)');
            	      string_th{10}=sprintf('(ft/sec^2)^2/(rad/sec)');
            	      string_th{11}=sprintf('(in/sec^2)^2/(rad/sec)');
            
            end
            if(app.nChoice == app.n_jerk )
            
                      string_th{1}=sprintf('G/s ');
            	      string_th{2}=sprintf('in/sec^3');
            		  string_th{3}=sprintf('ft/sec^3');
            		  string_th{4}=sprintf('mm/sec^3');
            		  string_th{5}=sprintf('meter/sec^3');
            end
            if(app.nChoice == app.n_temperature )
            
                      string_th{1}=sprintf('degrees F ');
            	      string_th{2}=sprintf('degrees C');
            		  string_th{3}=sprintf('degrees Rankine');
            		  string_th{4}=sprintf('Kelvin');
            end
            if(app.nChoice == app.n_mass )
            
                      string_th{1}=sprintf('pound-mass (lbm) ');
            	      string_th{2}=sprintf('slugs (lbf sec^2/ft)');
            		  string_th{3}=sprintf('lbf sec^2/in');
            		  string_th{4}=sprintf('ounces');
            		  string_th{5}=sprintf('kg');
            		  string_th{6}=sprintf('grams');
            		  string_th{7}=sprintf('mg');
            		  string_th{8}=sprintf('grains');
            
            end
            if(app.nChoice == app.n_mmi )
            
                      string_th{1}=sprintf('lbm ft^2 ');
            	      string_th{2}=sprintf('lbm in^2');
                      string_th{3}=sprintf('slugs ft^2 ');
            	      string_th{4}=sprintf('slugs in^2');
            	      string_th{5}=sprintf('lbf sec^2 in');
            		  string_th{6}=sprintf('kg m^2');
            
            end
            if(app.nChoice == app.n_massperlength)
            
                      string_th{1}=sprintf('kg/m ');
                      string_th{2}=sprintf('lbm/in ');
                      string_th{3}=sprintf('lbm/ft ');
            		  string_th{4}=sprintf('lbf sec^2/in^2');
            
            end
            if(app.nChoice == app.n_massperarea)
            
                      string_th{1}=sprintf('lbm/ft^2 ');
                      string_th{2}=sprintf('lbm/in^2 ');
                      string_th{3}=sprintf('slugs/ft^2 ');
                      string_th{4}=sprintf('slugs/in^2 ');
            		  string_th{5}=sprintf('lbf sec^2/in^3');
            		  string_th{6}=sprintf('kg/m^2');
            end
            if(app.nChoice == app.n_masspervolume )
            
                      string_th{1}=sprintf('lbm/ft^3 ');
                      string_th{2}=sprintf('lbm/in^3 ');
                      string_th{3}=sprintf('slugs/ft^3 ');
                      string_th{4}=sprintf('slugs/in^3 ');
            		  string_th{5}=sprintf('lbf sec^2/in^4');
            		  string_th{6}=sprintf('kg/m^3');
            		  string_th{7}=sprintf('g/cm^3');
            		  string_th{8}=sprintf('specific gravity:H20');
            end
            if(app.nChoice == app.n_force )
            
                      string_th{1}=sprintf('pounds-force (lbf) ');
                      string_th{2}=sprintf('kips ');
            		  string_th{3}=sprintf('Newtons');
            		  string_th{4}=sprintf('dynes');
            end
            if(app.nChoice == app.n_impedance || app.nChoice == app.n_dc)
            
                      string_th{1}=sprintf('N sec/m ');
                      string_th{2}=sprintf('N sec/cm ');
                      string_th{3}=sprintf('lbf sec/in ');
            		  string_th{4}=sprintf('lbf sec/ft');
            
            end
            if(app.nChoice == app.n_pressure)
            
                      string_th{1}=sprintf('atmosphere ');
            	      string_th{2}=sprintf('psi');
            		  string_th{3}=sprintf('psf');
            		  string_th{4}=sprintf('Pascal ');
             		  string_th{5}=sprintf('dynes/cm^2 ');
             		  string_th{6}=sprintf('bar ');
             		  string_th{7}=sprintf('millibar ');
            		  string_th{8}=sprintf('mm Hg (torr)');
              		  string_th{9}=sprintf('inches Hg ');
            		  string_th{10}=sprintf('inches Water');
            end
            if(app.nChoice == app.n_frequency )
            
                      string_th{1}=sprintf('Hertz ');
            	      string_th{2}=sprintf('radians/sec');
            		  string_th{3}=sprintf('rpm');
            		  string_th{4}=sprintf('period (sec)');
            end
            if(app.nChoice == app.n_wavenumber )
            
                      string_th{1}=sprintf('radians/meter ');
            	      string_th{2}=sprintf('radians/ft');
            		  string_th{3}=sprintf('radians/inch');
            
            end
            if(app.nChoice == app.n_energy || app.nChoice == app.n_work )
            
                      string_th{1}=sprintf('Joules (N-m)');
            		  string_th{2}=sprintf('ergs');
            	      string_th{3}=sprintf('foot-lbf');
            		  string_th{4}=sprintf('Watt-hours');
            		  string_th{5}=sprintf('BTU');
            	      string_th{6}=sprintf('kilocalories');
            		  string_th{7}=sprintf('eV');
            end
            if(app.nChoice == app.n_power_per_area || app.nChoice == app.n_intensity)
            
                      string_th{1}=sprintf('dB [ref: 1.0e-12 W/m^2]');
            		  string_th{2}=sprintf('Watts/m^2');
            end
            if(app.nChoice == app.n_energy_per_mass )
                      string_th{1}=sprintf('J/kg');
            		  string_th{2}=sprintf('kJ/kg');
            		  string_th{3}=sprintf('ft-lbf/lbm');
            		  string_th{4}=sprintf('in-lbf/lbm');
            end
            if(app.nChoice == app.n_power )
                      string_th{1}=sprintf('Watts');
            		  string_th{2}=sprintf('kilowatts');
            	      string_th{3}=sprintf('foot-lbf/sec');
            		  string_th{4}=sprintf('horsepower');
            		  string_th{5}=sprintf('BTU/hour');
            		  string_th{6}=sprintf('dBm (ref 1 mW)');
            	      string_th{7}=sprintf('dB  (ref 1 pW)');
            end
            
            if(app.nChoice == app.n_angle)
                      string_th{1}=sprintf('degrees');
            		  string_th{2}=sprintf('radians');
            end
            if(app.nChoice == app.n_area )
                      string_th{1}=sprintf('meters^2');
            		  string_th{2}=sprintf('centimeters^2');
            		  string_th{3}=sprintf('millimeters^2');
            		  string_th{4}=sprintf('inches^2');
            		  string_th{5}=sprintf('feet^2');
            		  string_th{6}=sprintf('yards^2');
            		  string_th{7}=sprintf('miles^2');
            		  string_th{8}=sprintf('acres');
            end
            if(app.nChoice == app.n_kinematic_viscosity )
                      string_th{1}=sprintf('meters^2/sec');
            		  string_th{2}=sprintf('centimeters^2/sec');
            		  string_th{3}=sprintf('millimeters^2/sec');
            		  string_th{4}=sprintf('inches^2/sec');
            		  string_th{5}=sprintf('feet^2/sec');
            end
            if(app.nChoice == app.n_volume )
                      string_th{1}=sprintf('in^3');
            		  string_th{2}=sprintf('ft^3');
            		  string_th{3}=sprintf('miles^3');
            		  string_th{4}=sprintf('mm^3');
            		  string_th{5}=sprintf('cm^3');
            		  string_th{6}=sprintf('m^3');
            		  string_th{7}=sprintf('km^3');
            		  string_th{8}=sprintf('liters');
            		  string_th{9}=sprintf('gallons');
            		  string_th{10}=sprintf('quarts');
            	      string_th{11}=sprintf('pints');
            end
            if(app.nChoice == app.n_ami )
                      string_th{1}=sprintf('m^4');
                      string_th{2}=sprintf('cm^4');
            		  string_th{3}=sprintf('mm^4');
                      string_th{4}=sprintf('in^4');
                      string_th{5}=sprintf('ft^4');
            end
            if(app.nChoice == app.n_moment || app.nChoice == app.n_torque)
            		 string_th{1}=sprintf('N m');
            		 string_th{2}=sprintf('ft lbf');
            		 string_th{3}=sprintf('in lbf');
            end
            if(app.nChoice == app.n_stiffness_plate )
            		 string_th{1}=sprintf('N m');
            		 string_th{2}=sprintf('lbf ft');
            		 string_th{3}=sprintf('lbf in');
            		 string_th{4}=sprintf('lbm in^2/sec^2');
            end
            if(app.nChoice == app.n_stiffness_rotational)
            
            		 string_th{1}=sprintf('N m/rad');
            		 string_th{2}=sprintf('ft lbf/rad');
            		 string_th{3}=sprintf('in lbf/rad');
            		 string_th{4}=sprintf('N m/deg');
            		 string_th{5}=sprintf('ft lbf/deg');
            		 string_th{6}=sprintf('in lbf/deg');
            end
            if(app.nChoice == app.n_stiffness_translational)
            
            		  string_th{1}=sprintf('N/m');
            		  string_th{2}=sprintf('N/mm');
            		  string_th{3}=sprintf('lbf/ft');
            		  string_th{4}=sprintf('lbf/in');
            end
            
            app.listbox_input_unit.Items=string_th;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vibrationdata_unit_conversion_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            
            LD(app);
        end

        % Value changed function: listbox_dimension
        function listbox_dimension_Callback(app, event)
            LD(app);
        end

        % Value changed function: listbox_input_unit
        function listbox_input_unit_Callback(app, event)

            app.edit_results.Enable='off';
            ss=sprintf(' ');
            app.edit_results.Value=ss;


        end

        % Button pushed function: pushbutton_calculate
        function pushbutton_calculate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            
            Items = char(app.listbox_dimension.Items);
            im=app.listbox_dimension.Value;
            [ndim]=find_index(Items,im);           

            Items = char(app.listbox_input_unit.Items);
            im=app.listbox_input_unit.Value;
            [app.nChoiceUnits]=find_index(Items,im);           
                        
            
            app.value=str2num(app.edit_value.Value);

            if isempty(app.value)
                app.value=1;
            end

            
            % Update handles structure
            guidata(hObject, handles);
            
            if(ndim == app.n_acoustic_impedance )
                acoustic_impedance(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_acoustic_pressure )
            
            				acoustics(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_acoustic_pressure_water )
            
            				ref1 = 1.45e-010;
            				ref2 = 1.e-06;
            				acoustics(app, hObject, eventdata, handles);
            end

            if(ndim == app.n_length || ndim == app.n_displacement )
            
            				length(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_velocity || ndim == app.n_speed )
            
            				velox(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_acceleration )
            
            				accel(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_acceleration_PSD )
            
            				accel_PSD(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_ami )
            
            				ami(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_temperature )
            
            				temperature(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_jerk )
            
            				jerk(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_kinematic_viscosity )
            
            				kinematic_viscosity(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_mass )
            
            				mass(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_mmi )
            
            				mmi(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_massperlength )
            
            				massperlength(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_massperarea )
            
            				massperarea(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_masspervolume )
            
            				masspervolume(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_moment || ndim == app.n_torque)
            
            				moment(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_force )
            
            				force(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_pressure)
            
            				pressure(app, hObject, eventdata, handles);
            end
            if( ndim == app.n_energy_per_mass)
            
            				energy_per_mass(app, hObject, eventdata, handles);
            end
            if( ndim == app.n_power_per_area ||  ndim == app.n_intensity)
            
            				intensity(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_frequency)
            
            				frequency(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_impedance || ndim == app.n_dc )
            
            				impedance(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_energy || ndim == app.n_work)
            
            				energy(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_power)
            
            				power(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_angle)
            
            				angle(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_area)
            
            				area(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_volume)
            
            				volume(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_stiffness_plate)
            
            				stiffness_plate(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_stiffness_translational)
            
            				stiffness_translational(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_stiffness_rotational)
            
            				stiffness_rotational(app, hObject, eventdata, handles);
            end
            if(ndim == app.n_wavenumber)
            
            				wavenumber(app, hObject, eventdata, handles);
            end
            
            set(app.edit_results,'Enable','on');
        end

        % Button pushed function: pushbutton_clear_value
        function pushbutton_clear_value_Callback(app, event)

            app.edit_results.Enable='off';
            ss=sprintf(' ');
            app.edit_results.Value=ss;
            app.edit_value.Value=ss;

        end

        % Button pushed function: pushbutton_return
        function pushbutton_return_Callback(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'vibrationdata_unit_conversion';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'vibrationdata_unit_conversion_App.m  ver 1.4  by Tom Irvine';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.listbox_dimension = uilistbox(app.uipanel1);
            app.listbox_dimension.Items = {'acceleration', 'acceleration PSD', 'acoustic impedance', 'acoustic pressure in air', 'acoustic pressure in water', 'angle', 'area', 'area moment of inertia', 'damping coefficient', 'displacement', 'energy', 'energy/mass', 'force', 'frequency', 'impedance', 'intensity', 'jerk', 'kinematic viscosity', 'length', 'mass', 'mass moment of inertia', 'mass/length', 'mass/area', 'mass/volume', 'moment', 'power', 'power/area', 'pressure or stress', 'speed', 'stiffness, plate', 'stiffness, rotational', 'stiffness, translational', 'temperature', 'torque', 'velocity', 'volume', 'wavenumber', 'work'};
            app.listbox_dimension.ValueChangedFcn = createCallbackFcn(app, @listbox_dimension_Callback, true);
            app.listbox_dimension.Tag = 'listbox_dimension';
            app.listbox_dimension.Value = 'acceleration';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Dimension';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Select Input Unit';
            app.listbox_input_unit = uilistbox(app.uipanel1);
            app.listbox_input_unit.Items = {'Listbox'};
            app.listbox_input_unit.ValueChangedFcn = createCallbackFcn(app, @listbox_input_unit_Callback, true);
            app.listbox_input_unit.Tag = 'listbox_input_unit';
            app.listbox_input_unit.Value = 'Listbox';
            app.pushbutton_calculate = uibutton(app.uipanel1, 'push');
            app.pushbutton_calculate.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_calculate_Callback, true);
            app.pushbutton_calculate.Tag = 'pushbutton_calculate';
            app.pushbutton_calculate.Text = 'Calculate';
            app.pushbutton_return = uibutton(app.uipanel1, 'push');
            app.pushbutton_return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_return_Callback, true);
            app.pushbutton_return.Tag = 'pushbutton_return';
            app.pushbutton_return.Text = 'Return';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Enter Value';
            app.edit_value = uieditfield(app.uipanel1, 'text');
            app.edit_value.Tag = 'edit_value';
            app.pushbutton_clear_value = uibutton(app.uipanel1, 'push');
            app.pushbutton_clear_value.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clear_value_Callback, true);
            app.pushbutton_clear_value.Tag = 'pushbutton_clear_value';
            app.pushbutton_clear_value.Text = 'Clear Value';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Results, Equivalent Units';
            app.edit_results = uitextarea(app.uipanel1);
            app.edit_results.Tag = 'edit_results';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibrationdata_unit_conversion_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)vibrationdata_unit_conversion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end