classdef vrs_fds_base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRSFDSBaseUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        AmplitudeUnitsListBox           matlab.ui.control.ListBox
        AmplitudeUnitsListBoxLabel      matlab.ui.control.Label
        OuputFrequenciesListBox         matlab.ui.control.ListBox
        OuputFrequenciesListBoxLabel    matlab.ui.control.Label
        MaxFreqHzEditField              matlab.ui.control.EditField
        MaxFreqHzEditFieldLabel         matlab.ui.control.Label
        MinFreqHzEditField              matlab.ui.control.EditField
        MinFreqHzEditFieldLabel         matlab.ui.control.Label
        PlotFrequenciesLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        OnePSDTab                       matlab.ui.container.Tab
        Label_6                         matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecLabel                matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        GivenfnTab                      matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ResponsePSDTab                  matlab.ui.container.Tab
        FatigueExponentEditField_sfn    matlab.ui.control.EditField
        FatigueExponentEditField_sfnLabel  matlab.ui.control.Label
        PerformRelativeFatigueAnalysisListBox  matlab.ui.control.ListBox
        PerformRelativeFatigueAnalysisListBoxLabel  matlab.ui.control.Label
        FatigueTypeListBox_sfn          matlab.ui.control.ListBox
        FatigueTypeListBox_sfnLabel     matlab.ui.control.Label
        AmplificationFactorQEditField_sfn  matlab.ui.control.EditField
        AmplificationFactorQEditField_sfnLabel  matlab.ui.control.Label
        ResultsTextArea_sfn             matlab.ui.control.TextArea
        ResultsTextArea_2Label          matlab.ui.control.Label
        CalculateButton_3               matlab.ui.control.Button
        NaturalFrequencyHzEditField     matlab.ui.control.EditField
        NaturalFrequencyHzLabel         matlab.ui.control.Label
        SaveResponsePSDTab              matlab.ui.container.Tab
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        ExporttoMatlabWorkspaceListBox  matlab.ui.control.ListBox
        ExporttoMatlabWorkspaceListBoxLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        StressVelocityPSDTab            matlab.ui.container.Tab
        StressResponsePSDEditField      matlab.ui.control.EditField
        StressResponsePSDEditFieldLabel  matlab.ui.control.Label
        OutputArrayNamesLabel           matlab.ui.control.Label
        VelocityResponsePSDEditField    matlab.ui.control.EditField
        VelocityResponsePSDEditFieldLabel  matlab.ui.control.Label
        MaterialListBox_2               matlab.ui.control.ListBox
        MaterialListBox_2Label          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ProportionalityConstantEditField  matlab.ui.control.EditField
        ProportionalityConstantLabel    matlab.ui.control.Label
        SelectUnitsListBox              matlab.ui.control.ListBox
        SelectUnitsListBoxLabel         matlab.ui.control.Label
        MassDensitylbmin3EditField_sv   matlab.ui.control.NumericEditField
        MassDensitylbmin3EditField_svLabel  matlab.ui.control.Label
        ElasticModuluspsiEditField_sv   matlab.ui.control.NumericEditField
        ElasticModuluspsiEditFieldLabel_sv  matlab.ui.control.Label
        CalculateButton_6               matlab.ui.control.Button
        StressPSDDirlikFatigueCDITab    matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        EnterDataTab                    matlab.ui.container.Tab
        ViewEquationButton              matlab.ui.control.Button
        PEditField                      matlab.ui.control.EditField
        PEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.EditField
        AEditFieldLabel                 matlab.ui.control.Label
        RSminSmaxR1forfullyreversedloadingR1meantensilestressLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        StressUnitListBox               matlab.ui.control.ListBox
        StressUnitListBoxLabel          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        MaterialLibraryListBox          matlab.ui.control.ListBox
        MaterialLibraryListBoxLabel     matlab.ui.control.Label
        StressRatioEditField            matlab.ui.control.EditField
        StressRatioEditFieldLabel       matlab.ui.control.Label
        StressScaleFactorEditField      matlab.ui.control.EditField
        StressScaleFactorEditFieldLabel  matlab.ui.control.Label
        CalculateViewResultsTab         matlab.ui.container.Tab
        CumulativeDamageIndexResultsTextArea  matlab.ui.control.TextArea
        CumulativeDamageIndexResultsTextAreaLabel  matlab.ui.control.Label
        CalculateButton_CDI             matlab.ui.control.Button
        SteinbergFatigueTab_2           matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        CalculateButton_5               matlab.ui.control.Button
        RelativeDisplacementUnitListBox  matlab.ui.control.ListBox
        RelativeDisplacementUnitListBoxLabel  matlab.ui.control.Label
        Zlimitfor20millioncyclesEditField  matlab.ui.control.EditField
        Zlimitfor20millioncyclesEditFieldLabel  matlab.ui.control.Label
        inch3sigmaLabel                 matlab.ui.control.Label
        ResultsTextArea_3               matlab.ui.control.TextArea
        ResultsTextArea_3Label          matlab.ui.control.Label
        VRSFDSTab                       matlab.ui.container.Tab
        FatigueExponentEditField        matlab.ui.control.EditField
        FatigueExponentEditFieldLabel   matlab.ui.control.Label
        PerformFatigueAnalysisListBox   matlab.ui.control.ListBox
        PerformFatigueAnalysisListBoxLabel  matlab.ui.control.Label
        FatigueTypeListBox              matlab.ui.control.ListBox
        FatigueTypeListBoxLabel         matlab.ui.control.Label
        DirlikMethodLabel               matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        CalculateTab                    matlab.ui.container.Tab
        NumberofQCasesListBox           matlab.ui.control.ListBox
        NumberofQCasesLabel             matlab.ui.control.Label
        UITable_Q                       matlab.ui.control.Table
        CalculateButton_one_PSD_VRS     matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        ExportTab_3                     matlab.ui.container.Tab
        QvaluewillbeappendedtooutputarraynamesLabel  matlab.ui.control.Label
        FDSOutputArrayNameEditField     matlab.ui.control.EditField
        FDSOutputArrayNameEditFieldLabel  matlab.ui.control.Label
        SaveFDSButton                   matlab.ui.control.Button
        VRSTypeListBox_2                matlab.ui.control.ListBox
        VRSTypeListBox_2Label           matlab.ui.control.Label
        VRSOutputArrayNameEditField     matlab.ui.control.EditField
        VRSOutputArrayNameEditFieldLabel  matlab.ui.control.Label
        VRSTypeListBox                  matlab.ui.control.ListBox
        SaveVRSButton                   matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        TwoPSDsTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        VRSFDSTab_2                     matlab.ui.container.Tab
        AmplificationFactorQEditField_4  matlab.ui.control.EditField
        AmplificationFactorQEditField_4Label  matlab.ui.control.Label
        Duration2secEditField           matlab.ui.control.EditField
        Duration2secEditFieldLabel      matlab.ui.control.Label
        Legend2EditField                matlab.ui.control.EditField
        Legend2EditFieldLabel           matlab.ui.control.Label
        SelectInputArray2ListBox        matlab.ui.control.ListBox
        SelectInputArray2ListBoxLabel   matlab.ui.control.Label
        Duration1secEditField           matlab.ui.control.EditField
        Duration1secEditFieldLabel      matlab.ui.control.Label
        Legend1EditField                matlab.ui.control.EditField
        Legend1EditFieldLabel           matlab.ui.control.Label
        SelectInputArray1ListBox        matlab.ui.control.ListBox
        SelectInputArray1ListBoxLabel   matlab.ui.control.Label
        UpdateListButton_2              matlab.ui.control.Button
        text1_2                         matlab.ui.control.Label
        listbox_method_2                matlab.ui.control.ListBox
        CalculateButton_4               matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        VRSTypeListBox_3                matlab.ui.control.ListBox
        VRSTypeListBox_3Label           matlab.ui.control.Label
        ArrayNumberListBox              matlab.ui.control.ListBox
        ArrayNumberListBoxLabel         matlab.ui.control.Label
        FDSOutputArrayNameEditField_2   matlab.ui.control.EditField
        FDSOutputArrayNameEditField_2Label  matlab.ui.control.Label
        VRSOutputArrayNameEditField_2   matlab.ui.control.EditField
        VRSOutputArrayNameEditField_2Label  matlab.ui.control.Label
        SaveFDSButton_2                 matlab.ui.control.Button
        SaveVRSButton_2                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        vrs_fds_basever10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM    % Description
        method %
        Q      % 
        nQ     %
        dur    %
        fmin   %
        fmax   %
        ispace %
        damp   %
        ai     %
        fi     %
        fn     %
        nfds   %
        iu     %
        rms    %
        df     %
        spec_minf   %
        spec_maxf   %        
        f      %
        a      % 
        vtypes %
        num    %
        bex    %
        irp    %
        fds_1  %
        fds_2  %
        E      %
        rho    %
        FS     %

        accel_vrs_peak_1   %
        accel_vrs_peak_2   %
        accel_vrs_grms_1   %
        accel_vrs_grms_2   %        
        
        accel_vrs_1_sigma  %
        accel_vrs_3_sigma  %
        accel_vrs_peak     %
        pv_vrs_1_sigma     %
        pv_vrs_3_sigma     %
        pv_vrs_peak        %
        rd_vrs_1_sigma     %
        rd_vrs_3_sigma     %
        rd_vrs_peak        %
        fds                %
        RDU                %
        rel_damage         %
        nMat               %
        mlab               %

        accel_response_psd  %
        accel_power_trans   %
        rd_response_psd     %    
    end
    
    methods (Access = private)

        function ChangeQ(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';
            app.nQ=str2num(app.NumberofQCasesListBox.Value);
            if(app.nQ==1)
                q=10;
            end
            if(app.nQ==2)
                q=[10;30];
            end    
             if(app.nQ==3)
                q=[10;20;30];
            end   
            app.UITable_Q.Data=q;  
            s = uistyle('HorizontalAlignment','center'); % create a style
            addStyle(app.UITable_Q,s) % adjust the style     
        end        

        function materials(app)
            Items = char(app.MaterialLibraryListBox.Items);
            im=app.MaterialLibraryListBox.Value;
            [app.nMat]=find_index(Items,im);
          
            % 1. Aluminum 6061-T6
            % 2. Aluminum 7050-T7451
            % 3. Aluminum 7075-T6
            % 4. Inconel 718
            % 5. Stainless Steel Custom 450 (H1050)
            % 6. Stainless Steel 15-5PH, 1025 

            [app.mlab,As,Bs,Cs,Ps]=SN_coefficients(app.nMat); 
 
            app.AEditField.Value=As;
            app.BEditField.Value=Bs;            
            app.CEditField.Value=Cs;
            app.PEditField.Value=Ps;

        end

        function single_fn_relative_damage(app)
            Items = char(app.PerformRelativeFatigueAnalysisListBox.Items);
            im=app.PerformRelativeFatigueAnalysisListBox.Value;
            [app.rel_damage]=find_index(Items,im);

            if(app.rel_damage==1)
                app.FatigueExponentEditField_sfn.Visible='off';
                app.FatigueExponentEditField_sfnLabel.Visible='off';
                app.FatigueTypeListBox_sfn.Visible='off';
                app.FatigueTypeListBox_sfnLabel.Visible='off';
            else    
                app.FatigueExponentEditField_sfn.Visible='on';
                app.FatigueExponentEditField_sfnLabel.Visible='on'; 
                app.FatigueTypeListBox_sfn.Visible='on';
                app.FatigueTypeListBox_sfnLabel.Visible='on';              
            end
            
        end
    
        function SV_Change(app)

            Items = char(app.SelectUnitsListBox.Items);
            im=app.SelectUnitsListBox.Value;
            [app.iu]=find_index(Items,im);

            Items = char(app.MaterialListBox_2.Items);
            im=app.MaterialListBox_2.Value;
            [imat]=find_index(Items,im);                

            [app.E,app.rho,~]=six_materials(app.iu,imat);

            if(app.iu==1)
                s1=sprintf('Elastic\n Modulus (psi)');
                s2=sprintf('Mass Density\n (lbm/in^3)');
            else    
                s1=sprintf('Elastic\n Modulus (GPa)');
                s2=sprintf('Mass Density\n (kg/m^3)');                    
            end    

            app.ElasticModuluspsiEditFieldLabel_sv.Text=s1;
            app.MassDensitylbmin3EditField_svLabel.Text=s2;

            app.ElasticModuluspsiEditField_sv.Value=app.E;
            app.MassDensitylbmin3EditField_sv.Value=app.rho;        
            
        end    

        function RelDispUnit(app)
            Items = char(app.RelativeDisplacementUnitListBox.Items);
            im=app.RelativeDisplacementUnitListBox.Value;
            [app.RDU]=find_index(Items,im);

            if(app.RDU==1)
                app.inch3sigmaLabel.Text='inch (3-sigma)';
            else    
                app.inch3sigmaLabel.Text='mm (3-sigma)';
            end    
        end    

        function one_psd(app)

            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==1)

                 try
                     app.FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',app.FS)
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            end

            if(isempty(app.THM))
                warndlg('Input array is empty');
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.f=app.THM(:,1);
            app.a=app.THM(:,2);

            [~,app.rms] = calculate_PSD_slopes_no(app.f,app.a);

            frequencies(app);
            
            [app.fi,app.ai] = interpolate_PSD_arbitrary_frequency(app.f,app.a,app.fn);

            n=length(app.THM(:,1));
            app.spec_minf=app.THM(1,1);
            app.spec_maxf=app.THM(n,1);

        end    

        function VRS_FDS(app)
            Items = char(app.PerformFatigueAnalysisListBox.Items);
            im=app.PerformFatigueAnalysisListBox.Value;
            [app.nfds]=find_index(Items,im);

            if(app.nfds==1)
                app.FatigueExponentEditField.Visible='off';
                app.FatigueExponentEditFieldLabel.Visible='off';
                app.FatigueTypeListBox.Visible='off';
                app.FatigueTypeListBoxLabel.Visible='off';
            else    
                app.FatigueExponentEditField.Visible='on';
                app.FatigueExponentEditFieldLabel.Visible='on';
                app.FatigueTypeListBox.Visible='on';
                app.FatigueTypeListBoxLabel.Visible='on';              
            end
            
        end

        function frequencies(app)
            
            clear app.fn;

            frange=app.THM(end,1)-app.THM(1,1);

            if(app.ispace==1)
                app.df=0.25;
                app.num=floor(frange/app.df);
                app.fn=app.df*(0:app.num)+app.THM(1,1);
            end
            if(app.ispace==2)
                app.df=0.5;
                app.num=floor(frange/app.df);
                app.fn=app.df*(0:app.num)+app.THM(1,1);
            end
            if(app.ispace>=3)

                ioct=app.ispace-2+2;

                [app.fn,~]=octaves(ioct);

                f1=app.THM(1,1);
                f2=app.THM(end,1);

                [~,ii]=min(abs(app.fn-f1));
                [~,jj]=min(abs(app.fn-f2));
     
                app.fn=app.fn(ii:jj);
                app.fn(1)=f1;
                app.fn(end)=f2;
                
            end      

        end   

       function update_2(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArray1ListBox.Items=unique(sort(cell_array));
            catch
            end
            try
                app.SelectInputArray1ListBox.Value=app.SelectInputArray1ListBox.Items{1};
            catch
                disp('ref 1')
            end
            try    
                app.SelectInputArray2ListBox.Items=unique(sort(cell_array));
            catch
            end
            try
                app.SelectInputArray2ListBox.Value=app.SelectInputArray2ListBox.Items{1};
            catch
                disp('ref 2')      
            end          
        end  

        function update_alt(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
        end        

        function update(app)
            [cell_array]=update_power_spectral_density();
            try    
                app.SelectInputArrayListBox.Items=unique(sort(cell_array));
            catch
            end
            try
                app.SelectInputArrayListBox.Value=app.SelectInputArrayListBox.Items{1};
            catch
            end
        end   

        function clear(app)
            ClosePlots(app);
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';        
            app.ResultsTextArea_sfn.Visible='off';
            app.ResultsTextArea_2Label.Visible='off';     
            app.SaveButton.Enable='off';
            app.SaveVRSButton.Enable='off';
            app.SaveFDSButton.Enable='off';
            app.CumulativeDamageIndexResultsTextArea.Visible='off';
            app.CumulativeDamageIndexResultsTextAreaLabel.Visible='off';
        end    
        
        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        function parameters(app)

            app.fmin=str2num(app.MinFreqHzEditField.Value);
            app.fmax=str2num(app.MaxFreqHzEditField.Value);

            Items = char(app.OuputFrequenciesListBox.Items);
            im=app.OuputFrequenciesListBox.Value;
            [app.ispace]=find_index(Items,im);

            Items = char(app.AmplitudeUnitsListBox.Items);
            im=app.AmplitudeUnitsListBox.Value;
            [app.iu]=find_index(Items,im);
        
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            minimum_workmanship();
            ClosePlots(app);
            update(app);
            update_2(app);
            VRS_FDS(app);
            RelDispUnit(app);
            SV_Change(app);
            single_fn_relative_damage(app);
            materials(app);
            ChangeQ(app); 
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: OuputFrequenciesListBox
        function OuputFrequenciesListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: MinFreqHzEditField
        function MinFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: MaxFreqHzEditField
        function MaxFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
        end

        % Button pushed function: CalculateButton_one_PSD_VRS
        function CalculateButton_one_PSD_VRSPushed(app, event)
            ClosePlots(app);
            parameters(app);
            one_psd(app);

            Items = char(app.FatigueTypeListBox.Items);
            im=app.FatigueTypeListBox.Value;
            [app.irp]=find_index(Items,im);

            app.bex=str2num(app.FatigueExponentEditField.Value);
            app.dur=str2num(app.DurationsecEditField.Value); 

            A=app.UITable_Q.Data;
            for i=1:app.nQ
                app.Q(i)=A(i);
            end

            app.SaveVRSButton.Enable='on';
            
            idc=2;
            
            if(app.nfds==2)
                
                if(isempty(app.bex))
                    warndlg('Enter fatigue exponent');
                    return;
                end
                
                idc=1;

                app.SaveFDSButton.Enable='on';
            end        

            frequencies(app);

            clear length;
                
            L=length(app.fn);
    
            kv=0;
    
            for i=1:L
                if(app.fn(i)>=app.spec_minf && app.fn(i)<=app.spec_maxf)
                    kv=kv+1;
                end   
            end   

            nnn=app.nQ+1;
    
            app.accel_vrs_peak=zeros(kv,nnn);
            app.pv_vrs_peak=zeros(kv,nnn);
            app.rd_vrs_peak=zeros(kv,nnn);                
            
            j=1;
                 
            for i=1:L
                if(app.fn(i)>=app.spec_minf && app.fn(i)<=app.spec_maxf)

                        app.accel_vrs_peak(j,1)=app.fn(i);
                           app.pv_vrs_peak(j,1)=app.fn(i);        
                           app.rd_vrs_peak(j,1)=app.fn(i);
                           j=j+1;
                end   
            end

            disp(' ref 1')


            for ijk=1:app.nQ
                app.damp=1/(2*app.Q(ijk));

                [app.fn,qa_vrs,qpv_vrs,qrd_vrs,qdamage]=...
                         vibrationdata_fds_vrs_engine_fn_alt(app.fi,app.ai,...
                             app.damp,app.iu,idc,app.irp,app.bex,...
                             app.dur,app.fn); 

                qrd_vrs(1:10)

                if(ijk==1)
                    app.fn=fix_size(app.fn);
                    nL=length(app.fn);
                    a_vrs=zeros(nL,app.nQ);
                    pv_vrs=zeros(nL,app.nQ);
                    rd_vrs=zeros(nL,app.nQ);
                    damage=zeros(nL,app.nQ);
                end    

                a_vrs(:,ijk)=qa_vrs;
                pv_vrs(:,ijk)=qpv_vrs;
                rd_vrs(:,ijk)=qrd_vrs;
                damage(:,ijk)=qdamage;
                
                j=1;
                 
                for i=1:L
                %    
                    if(app.fn(i)>=app.spec_minf && app.fn(i)<=app.spec_maxf)
                %
                        ax=0.5;
                        [ms]=risk_overshoot(app.fn(i),app.dur,ax);
                %
                        nv=ijk+1;
                        app.accel_vrs_peak(j,nv)=ms*a_vrs(i,ijk);
                           app.pv_vrs_peak(j,nv)=ms*pv_vrs(i,ijk);        
                           app.rd_vrs_peak(j,nv)=ms*rd_vrs(i,ijk);
                           j=j+1;
                    end   
                %       
                end

            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            fig_num=1;
            power_spectral_density=[app.f app.a];
            xlab='Frequency (Hz)';
            if(app.iu<=1)
                ylab='Accel (G^2/Hz)';
                YS='G';
            else
                ylab='Accel ((m/sec^2)^2/Hz)';
                YS='(m/sec^2)^2';
            end
            
            t_string=...
                sprintf(' Power Spectral Density  Overall Level = %6.3g %sRMS ',app.rms,YS); 
                [fig_num,~]=plot_PSD_function(fig_num,xlab,ylab,t_string,power_spectral_density,app.fmin,app.fmax);
                    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    

            app.accel_vrs_1_sigma=[app.fn a_vrs];
            app.accel_vrs_3_sigma=[app.fn 3*a_vrs];
            
               app.pv_vrs_1_sigma=[app.fn pv_vrs];
               app.pv_vrs_3_sigma=[app.fn 3*pv_vrs];
               
               app.rd_vrs_1_sigma=[app.fn rd_vrs];
               app.rd_vrs_3_sigma=[app.fn 3*rd_vrs];
                          app.fds=[app.fn damage];
            
            %%%%%%%%%%%%%%%%%%%%

            leg1='peak';
            leg2='3-sigma';
            leg3='1-sigma';
            
            x_label='Natural Frequency (Hz)';
            if(app.iu<=2)
                y_label='Accel (G)';
            else
                y_label='Accel ((m/sec^2))';    
            end

            md=6;

            for ijk=1:app.nQ
                ppp1=[app.fn app.accel_vrs_peak(:,ijk+1)];
                ppp2=[app.fn 3*a_vrs(:,ijk)];
                ppp3=[app.fn   a_vrs(:,ijk)]; 

                t_string = sprintf(' Acceleration Vibration Response Spectrum Q=%g ',app.Q(ijk));
                [fig_num]=plot_loglog_function_md_three(fig_num,x_label,...
                           y_label,t_string,ppp1,ppp2,ppp3,leg1,leg2,leg3,app.fmin,app.fmax,md);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [~,n1] = min(abs(app.fn-app.fmin));
            [~,n2] = min(abs(app.fn-app.fmax));

            for ijk=1:app.nQ

                vvv=app.pv_vrs_peak;
                [C,I]=max(vvv(n1:n2,ijk+1));
                fff=strtrim(sprintf('%7.3g',vvv(I,1))); 

                if(ijk==1)
                    if(app.iu<=1)
                        ss=sprintf('Q=%g, Max Peak PV = %7.3g in/sec at %s Hz\n',app.Q(ijk),C,fff);
                    else    
                        ss=sprintf('Q=%g, Max Peak PV = %7.3g m/sec at %s Hz\n',app.Q(ijk),C,fff);
                    end          
                else
                    if(app.iu<=1)
                        ss=sprintf('%s\nQ=%g, Max Peak PV = %7.3g in/sec at %s Hz\n',ss,app.Q(ijk),C,fff);
                    else    
                        ss=sprintf('%s\nQ=%g, Max Peak PV = %7.3g m/sec at %s Hz\n',ss,app.Q(ijk),C,fff);
                    end   
                end    
            end

            app.ResultsTextArea.Value=ss;
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';     


            for ijk=1:app.nQ
                    try
                        data1=[ app.accel_vrs_peak(:,ijk+1),3*a_vrs(:,ijk),a_vrs(:,ijk)];
                        data2=[ app.pv_vrs_peak(:,ijk+1),3*pv_vrs(:,ijk),pv_vrs(:,ijk)];
                        data3=[ app.rd_vrs_peak(:,ijk+1),3*rd_vrs(:,ijk),rd_vrs(:,ijk)];   
                    catch
                        disp('ref 1');
                    end

                    try
                        [fig_num]=subplots_vrs_set(fig_num,app.fn,data1,data2,data3,app.Q(ijk),app.fmin,app.fmax,app.iu); 
                    catch
                        disp('ref 2');            
                    end    
            end
                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if(idc==1)
                
                if(app.irp==1)
                    if(app.iu==1)
                        y_label=sprintf('Damage (G^{ %g})',app.bex);
                    else
                        y_label=sprintf('Damage ((m/sec^2)^{ %g})',app.bex);        
                    end
                end
                if(app.irp==2)
                    if(app.iu==1)
                        y_label=sprintf('Damage (ips^{ %g})',app.bex);
                    else
                        y_label=sprintf('Damage ((m/sec)^{ %g})',app.bex);        
                    end                     
                end
                if(app.irp==3)
                    if(app.iu==1)
                        y_label=sprintf('Damage (in^{ %g})',app.bex);
                    else
                        y_label=sprintf('Damage (mm^{ %g})',app.bex);        
                    end                   
                end
           
                x_label='Natural Frequency (Hz)';

                for ijk=1:app.nQ
                    if(app.irp==1)
                        t_string=sprintf('Acceleration FDS  Q=%g b=%g ',app.Q(ijk),app.bex);
                    end
                    if(app.irp==2)
                        t_string=sprintf('Pseudo Velocity FDS  Q=%g b=%g ',app.Q(ijk),app.bex);                    
                    end
                    if(app.irp==3)
                        t_string=sprintf('Rel Disp FDS  Q=%g b=%g ',app.Q(ijk),app.bex);              
                    end                   
                    ppp=[app.fn damage(:,ijk)];
                    [fig_num]=plot_fds_function_alt(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax);
                end
           
            end

        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
            ClosePlots(app);
            parameters(app);

            app.Q=str2num(app.AmplificationFactorQEditField_4.Value);
            app.damp=1/(2*app.Q);
   
            fig_num=1;

            %%%%

            try
                app.FS=app.SelectInputArray1ListBox.Value
                THM_1=evalin('base',app.FS);
            catch
                warndlg('Input Array 1 does not exist.  Try reading in external file.')
                return;
            end

            try
                app.FS=app.SelectInputArray2ListBox.Value
                THM_2=evalin('base',app.FS);
            catch
                warndlg('Input Array 2 does not exist.  Try reading in external file.')
                return;
            end

            %%%%%

            p=app.iu;
            
            [~,input_rms_1] = calculate_PSD_slopes(THM_1(:,1),THM_1(:,2));
            [~,input_rms_2] = calculate_PSD_slopes(THM_2(:,1),THM_2(:,2));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.df=0.5;
            s=0;
            %
            app.f=THM_1(:,1);
            app.a=THM_1(:,2);
            [fi_1,ai_1]=interpolate_PSD(app.f,app.a,s,app.df);
            
            dur1=str2num(app.Duration1secEditField.Value);

            idc=1;
            
            if(app.nfds==1)
                idc=2;
            end
            
            [fn1,avrs1,pv_vrs1,rd_vrs1,damage1]=...
                         vibrationdata_fds_vrs_engine(fi_1,ai_1,app.damp,app.df,p,idc,app.irp,app.bex,dur1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.f=THM_2(:,1);
            app.a=THM_2(:,2);
            [fi_2,ai_2]=interpolate_PSD(app.f,app.a,s,app.df);
            
            dur2=str2num(app.Duration2secEditField.Value);
            
            [fn2,avrs2,pv_vrs2,rd_vrs2,damage2]=...
                         vibrationdata_fds_vrs_engine(fi_2,ai_2,app.damp,app.df,p,idc,app.irp,app.bex,dur2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            avrs1=abs(avrs1);
            avrs2=abs(avrs2);
            
            fn1=fix_size(fn1);
            fn2=fix_size(fn2);
            
            avrs1=fix_size(avrs1);
            avrs2=fix_size(avrs2);
            
            app.accel_vrs_grms_1=[fn1 avrs1];
            app.accel_vrs_grms_2=[fn2 avrs2];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            t_string='Power Spectral Density';
            x_label='Frequency (Hz)';
            
            if(app.iu<=2)
                y_label='Accel (G^2/Hz)';
            else
                y_label='Accel ((m/sec^2)^2/Hz)';    
            end    
            
            ppp=THM_1;
            qqq=THM_2;
            
            leg1=app.Legend1EditField.Value;
            leg2=app.Legend2EditField.Value;
            
            leg_a=sprintf('%s %6.3g GRMS',leg1,input_rms_1);
            leg_b=sprintf('%s %6.3g GRMS',leg2,input_rms_2);
            
            md=5;
            
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                           y_label,t_string,ppp,qqq,leg_a,leg_b,app.fmin,app.fmax,md);
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
                
            t_string = sprintf(' Acceleration Vibration Response Spectrum Q=%g ',app.Q);
            x_label='Natural Frequency (Hz)';
            
            if(app.iu<=2)
                y_label='Accel (GRMS)';
            else
                y_label='Accel (m/sec^2 RMS)'; 
            end  
            
            md=5;
            
            ppp1=app.accel_vrs_grms_1;
            ppp2=app.accel_vrs_grms_2;
            
            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                         y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
            clear length;
            
            L1=length(fn1);
            app.accel_vrs_peak_1=zeros(L1,1);
            
            ax=0.5;
            
            for i=1:L1
                [ms]=risk_overshoot(fn1(i),dur1,ax);
                app.accel_vrs_peak_1(i)=ms*avrs1(i);
            end
            
            L2=length(fn2);
            app.accel_vrs_peak_2=zeros(L2,1);
            
            for i=1:L2
                [ms]=risk_overshoot(fn2(i),dur2,ax);    
                app.accel_vrs_peak_2(i)=ms*avrs2(i);
            end
            
            %%%%
            
            t_string = sprintf(' Peak Acceleration Vibration Response Spectrum Q=%g ',app.Q);
            x_label='Natural Frequency (Hz)';
            
            if(app.iu<=2)
                y_label='Peak Accel (G)';
            else
                y_label='Peak Accel (m/sec^2)'; 
            end  
            
            md=5;
            
            fn1=abs(fn1);
            fn2=abs(fn2);
            app.accel_vrs_peak_1=abs(app.accel_vrs_peak_1);
            app.accel_vrs_peak_2=abs(app.accel_vrs_peak_2);
            
            ppp1=[ fn1 app.accel_vrs_peak_1];
            ppp2=[ fn2 app.accel_vrs_peak_2];

            app.accel_vrs_peak_1=ppp1;
            app.accel_vrs_peak_2=ppp2;

            [fig_num,h2]=plot_loglog_function_md_two_h2(fig_num,x_label,...
                         y_label,t_string,ppp1,ppp2,leg1,leg2,app.fmin,app.fmax,md);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(idc==1)
            %
                disp(' ');
                disp(' The fatigue damage spectrum is (peak-valley)/2 ');
                if(app.irp==1)
                   out1 = sprintf(' Acceleration Fatigue Damage Spectrum Q=%g b=%g',app.Q,app.bex);
                   if(app.iu<=2)
                       ylab=sprintf('Damage Index [log10(G^{ %g })]',app.bex);
                   else
                       ylab=sprintf('Damage Index [log10((m/sec^2)^{ %g })]',app.bex);           
                   end    
                end
                if(app.irp==2)
                   if(app.iu==1) 
                        out1 = sprintf(' Pseudo Velocity Fatigue Damage Spectrum Q=%g b=%g',app.Q,app.bex);
                        ylab=sprintf('Damage Index [log10(ips^{ %g })]',app.bex);             
                   else
                        out1 = sprintf(' Pseudo Velocity Fatigue Damage Spectrum Q=%g b=%g',app.Q,app.bex);           
                        ylab=sprintf('Damage Index [log10((m/sec)^{ %g })]',app.bex);         
                   end    
                end
                if(app.irp==3)
                   if(app.iu==1)  
                        out1 = sprintf(' Relative Disp Fatigue Damage Spectrum Q=%g b=%g',app.Q,app.bex); 
                        ylab=sprintf('Damage Index [log10(in^{ %g })]',app.bex);
                   else
                        out1 = sprintf(' Relative Disp Fatigue Damage Spectrum Q=%g b=%g',app.Q,app.bex); 
                        ylab=sprintf('Damage Index [log10(mm^{ %g })]',app.bex);            
                   end    
                end
            %
                ppp=[ fn1 damage1];
                qqq=[ fn2 damage2];

                app.fds_1=ppp;
                app.fds_2=qqq;
            
                leg_a=leg1;
                leg_b=leg2;
                
                y_label=ylab;
                x_label='Natural Frequency (Hz)';
                
                tstring=out1;
               
                [fig_num,h2]=plot_fds_two_alt_h2(fig_num,x_label,y_label,ppp,qqq,leg_a,leg_b,tstring,app.fmin,app.fmax);
            
            end


        end

        % Callback function
        function PerformFatigueAnalysisListBoxValueChanged(app, event)
            VRS_FDS(app);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)

            ClosePlots(app);
            parameters(app);     
            one_psd(app);

            Items = char(app.FatigueTypeListBox_sfn.Items);
            im=app.FatigueTypeListBox_sfn.Value;
            [app.irp]=find_index(Items,im);            

            app.bex=str2num(app.FatigueExponentEditField_sfn.Value);

            app.Q=str2num(app.AmplificationFactorQEditField_sfn.Value);
            app.damp=1/(2*app.Q);

            app.fn=str2num(app.NaturalFrequencyHzEditField.Value);            

            if(app.fn<=0)
                warndlg('Natural frequency must be > 0');
                return;
            end
            
            natural_frequency=app.fn;
            omegan=2*pi*app.fn;      

            [a_vrs,rd_vrs,trans,opsd,rd_psd]=...
                             vibrationdata_sdof_ran_engine_function_ard(app.fi,app.ai,app.damp,natural_frequency);
            
            if(app.iu==1)
                rd_vrs=rd_vrs*386;
                rd_psd=rd_psd*386^2;
            end
            if(app.iu==2)
                rd_vrs=rd_vrs*9.81*1000;
                rd_psd=rd_psd*(9.81*1000)^2;
            end
            if(app.iu==3)
                rd_vrs=rd_vrs*1000;
                rd_psd=rd_psd*1000^2;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.dur=str2num(app.DurationsecEditField.Value);
            ax=0.5;
            [ms]=risk_overshoot(app.fn,app.dur,ax);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%f

            if(app.ispace==1 && app.ispace==2)   
                [EP,vo,m0,m1,m2,m4]=spectal_moments(app.fi,opsd,app.df);    
            else
                [EP,vo,m0,m1,m2,m4]=spectal_moments_oct(app.fi,opsd);
            end

            fprintf('\n m0=%8.4g  m2=%8.4g  m4=%8.4g \n',m0,m2,m4);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            string_acc=sprintf(' SDOF Acceleration Response ');
            string_pv=sprintf('\n\n SDOF Pseudo Velocity Response ');
            string_rd=sprintf('\n\n SDOF Relative Displacement Response ');
            
            if(app.iu<=2)
                out1=sprintf('\n   = %8.3g GRMS',a_vrs);
                out2=sprintf('\n   = %8.3g G 3-sigma',3.*a_vrs);
                out3=sprintf('\n   = %8.3g G %4.3g-sigma (max expected)',ms*a_vrs,ms);
                if(app.irp==1)
                    su='G';
                end    
            else
                out1=sprintf('\n   = %8.3g (m/sec^2)RMS',a_vrs);
                out2=sprintf('\n   = %8.3g (m/sec^2) 3-sigma',3.*a_vrs);
                out3=sprintf('\n   = %8.3g (m/sec^2) %4.3g-sigma (max expected)',ms*a_vrs,ms);   
                if(app.irp==1)
                    su='(m/sec^2)';
                end                  
            end    
            
            if(app.iu==1)
                out4=sprintf('\n   = %8.3g inch/sec RMS',omegan*rd_vrs);
                out5=sprintf('\n   = %8.3g inch/sec 3-sigma',omegan*3.*rd_vrs);
                out6=sprintf('\n   = %8.3g inch/sec %4.3g-sigma (max expected)',omegan*ms*rd_vrs,ms);     
                
                out7=sprintf('\n   = %8.3g inch RMS',rd_vrs);
                out8=sprintf('\n   = %8.3g inch 3-sigma',3.*rd_vrs);
                out9=sprintf('\n   = %8.3g inch %4.3g-sigma (max expected)',ms*rd_vrs,ms);   
                if(app.irp==2)
                    su='(in/sec)';
                end   
                if(app.irp==3)
                    su='in';
                end                
                
            else
                out4=sprintf('\n   = %8.3g mm/sec RMS',omegan*rd_vrs);
                out5=sprintf('\n   = %8.3g mm/sec 3-sigma',omegan*3.*rd_vrs);
                out6=sprintf('\n   = %8.3g mm/sec %4.3g-sigma (max expected)',omegan*ms*rd_vrs,ms); 
                
                out7=sprintf('\n   = %8.3g mm RMS',rd_vrs);
                out8=sprintf('\n   = %8.3g mm 3-sigma',3.*rd_vrs);
                out9=sprintf('\n   = %8.3g mm %4.3g-sigma (max expected)',ms*rd_vrs,ms);  
                
                if(app.irp==2)
                    su='(mm/sec)';
                end   
                if(app.irp==3)
                    su='mm';
                end                     
            end

            Ne=2*EP*app.dur;
            e=sqrt(m2^2/(m0*m4));
            
            % fun = @(x) (   1-(1-e*exp(-x.^2)).^Ne  );
            % pf = sqrt(2)*integral(fun,0,Inf);
            
            out90=sprintf('\n\n Rate of Up-zero crossings = %7.4g Hz',vo);
            out91=sprintf('\n             Rate of peaks = %7.4g Hz',EP);
            
            str = sprintf('%c ', 958);
            out92=sprintf('\n\n Acceleration Bandwidth %s = %7.3g \n',str,e);

            string_big=strcat(string_acc,out1);
            string_big=strcat(string_big,out2);
            string_big=strcat(string_big,out3);
            
            string_big=strcat(string_big,string_pv);
            string_big=strcat(string_big,out4);
            string_big=strcat(string_big,out5);
            string_big=strcat(string_big,out6);
            
            string_big=strcat(string_big,string_rd);
            string_big=strcat(string_big,out7);
            string_big=strcat(string_big,out8);
            string_big=strcat(string_big,out9);
            
            string_big=strcat(string_big,out90);
            string_big=strcat(string_big,out91);
            string_big=strcat(string_big,out92); 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fig_num=1;
            
            app.fi=fix_size(app.fi);
            opsd=fix_size(opsd);
            trans=fix_size(trans);
            
            rd_psd=fix_size(rd_psd);
            %
            ppp=[app.fi opsd];
            qqq=app.THM;
            %
            app.accel_response_psd=[app.fi opsd];
            app.accel_power_trans=[app.fi trans];
            app.rd_response_psd=[app.fi rd_psd];
            %  

            if(app.rel_damage==2)
                if(app.irp==1)
                    apsd=app.accel_response_psd;
                end    
                if(app.irp==2)
                    apsd=[app.fi omegan^2*rd_psd];
                end
                if(app.irp==3)
                    apsd=app.rd_response_psd;
                end    

                [damage]=Dirlik_fds(apsd,app.bex,app.dur,app.df);
                
                ss=sprintf('\n\n Relative Fatigue Damage = %7.3g %s^%g',damage,su,app.bex);            
                string_big=strcat(string_big,ss);  

            end    

            app.ResultsTextArea_sfn.Value=string_big;
            app.ResultsTextArea_sfn.Visible='on';
            app.ResultsTextArea_2Label.Visible='on';     

            x_label=sprintf(' Frequency (Hz)');
            %
            if(app.iu<=2)
               y_label=sprintf(' Accel (G^2/Hz)');
               leg_a=sprintf('Response %5.3g GRMS',a_vrs);
               leg_b=sprintf('Input %5.3g GRMS',app.rms);   
            else
               y_label=sprintf(' Accel ((m/sec^2)^2/Hz)');  
               leg_a=sprintf('Response %5.3g (m/sec^2)RMS',a_vrs);
               leg_b=sprintf('Input %5.3g (m/sec^2)RMS',app.input_rms);      
            end
             
            t_string = sprintf(' Power Spectral Density   fn=%g Hz  Q=%g  ',natural_frequency,app.Q);   
            %
            [fig_num,~]=plot_PSD_two_sdof_ran_f(fig_num,x_label,y_label,t_string,ppp,qqq,leg_a,leg_b,app.fmin,app.fmax); 
            %
            
            ppp=[app.fi trans];  
            x_label=sprintf(' Frequency (Hz)');
            if(app.iu<=2)
                y_label=sprintf(' Trans (G^2/G^2)'); 
            else
                y_label=sprintf(' Trans ((m/sec^2)^2/(m/sec^2)^2)');     
            end    
            t_string = sprintf(' Power Transmissibility   fn=%g Hz  Q=%g',natural_frequency,app.Q);   
            [fig_num,~]=plot_PSD_function(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax);
            %

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            [~,rd_rms] = calculate_PSD_slopes(app.rd_response_psd(:,1),app.rd_response_psd(:,2));
            
            if(app.iu<=1)
                y_label=sprintf('Rel Disp (inch^2/Hz)'); 
                t_string = sprintf(' Relative Displacement PSD   fn=%g Hz  Q=%g \n Overall Level = %7.3g in RMS',natural_frequency,app.Q,rd_rms);   
            else
                y_label=sprintf('Rel Disp (mm^2/Hz)');     
                t_string = sprintf(' Relative Displacement PSD   fn=%g Hz  Q=%g \n Overall Level = %7.3g mm RMS',natural_frequency,app.Q,rd_rms);       
            end 
            
            % [fig_num,h]=plot_PSD_function(fig_num,x_label,y_label,t_string,rd_response_psd,fmin,fmax);
            
            
            ppp=app.rd_response_psd;
            md=5;
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                         
            %
            app.SaveButton.Enable='on';
        end

        % Value changing function: NaturalFrequencyHzEditField
        function NaturalFrequencyHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function RelativeFatigueDamageButtonPushed(app, event)
            response_PSD_relative_damage;
        end

        % Callback function
        function SteinbergFatigueDamageButtonPushed(app, event)
            Steinberg_PSD_fatigue;
        end

        % Callback function
        function SaveButtonPushed(app, event)
            name=strtrim(app.OutputArrayNameEditField.Value);
            L=strtrim(app.ExporttoMatlabWorkspaceListBox.Value);
            
            if(strcmp(L,'Acceleration PSD'))
                data=app.accel_response_psd;
            end
            if(strcmp(L,'Relative Displacement PSD'))
                data=app.rd_response_psd; 
            end
            if(strcmp(L,'Acceleration Power Trans'))
                data=app.accel_power_trans;
            end         
            
            try
                assignin('base',name, data);
                msgbox('Save Complete');
            catch
                warndlg('save failed');
            end              
           
            update_alt(app);
     
        end

        % Button pushed function: SaveVRSButton
        function SaveVRSButtonPushed(app, event)
            app.vtypes=app.VRSTypeListBox_2.Value;
            
            if(contains(app.vtypes,'Acceleration'))
                vtype=1;
            end
            if(contains(app.vtypes,'Velocity'))
                vtype=2;
            end
            if(contains(app.vtypes,'Disp'))
                vtype=3;
            end   

            ss=app.VRSTypeListBox.Value;
            
            if(vtype==1)
                if(contains(ss,'1'))
                    data=app.accel_vrs_1_sigma;               
                end
                if(contains(ss,'3'))
                    data=app.accel_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.accel_vrs_peak;              
                end                   
            end
            if(vtype==2)
                if(contains(ss,'1'))             
                    data=app.pv_vrs_1_sigma;                      
                end
                if(contains(ss,'3'))
                    data=app.pv_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.pv_vrs_peak;                  
                end                    
            end
            if(vtype==3)
                if(contains(ss,'1'))
                    data=app.rd_vrs_1_sigma;                     
                end
                if(contains(ss,'3'))
                    data=app.rd_vrs_3_sigma;                      
                end                
                if(contains(ss,'peak'))
                    data=app.rd_vrs_peak;                    
                end                
            end    
                    
            name=app.VRSOutputArrayNameEditField.Value;
            
            for ijk=1:app.nQ
                try
                    dd=round(app.Q(ijk));
                    nameq=sprintf('%s_Q%d',name,dd);
                catch
                    disp('nameq failed');
                end

                try
                    if(ijk==1)
                        ss=sprintf('%s',nameq);
                    else
                        ss=sprintf('%s\n%s\n',ss,nameq);                    
                    end
                catch
                    disp('ss failed');
                end    

                try
                    assignin('base',nameq, [data(:,1) data(:,ijk+1)]);
                catch
                    warndlg('save failed');
                    return;
                end 
            end

            ss=sprintf('Saved arrays: \n%s',ss);
            msgbox(ss);
        end

        % Button pushed function: SaveFDSButton
        function SaveFDSButtonPushed(app, event)
            data=app.fds;
            name=app.FDSOutputArrayNameEditField.Value;

            for ijk=1:app.nQ
                try
                    dd=round(app.Q(ijk));
                    bb=sprintf('%g',app.bex);
                    bb=strrep(bb,'.','p');
                    nameq=sprintf('%s_Q%d_b%s',name,dd,bb);
                catch
                    disp('nameq failed');
                end

                try
                    if(ijk==1)
                        ss=sprintf('%s',nameq);
                    else
                        ss=sprintf('%s\n%s\n',ss,nameq);                    
                    end
                catch
                    disp('ss failed');
                end    

                try
                    assignin('base',nameq, [data(:,1) data(:,ijk+1)]);
                catch
                    warndlg('save failed');
                    return;
                end 
            end
            ss=sprintf('Saved arrays: \n%s',ss);
            msgbox(ss);
        end

        % Callback function
        function UpdateListButton_2Pushed(app, event)
            update_2(app);
        end

        % Callback function
        function listbox_method_2ValueChanged(app, event)
            Items = char(app.listbox_method_2.Items);
            im=app.listbox_method_2.Value;
            [app.method]=find_index(Items,im);

            if(app.method==2)
            
               try
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update_2(app);
               app.listbox_method_2.Value=app.listbox_method_2.Items{1};
            end    
        end

        % Button pushed function: SaveVRSButton_2
        function SaveVRSButton_2Pushed(app, event)
            Items = char(app.ArrayNumberListBox.Items);
            im=app.ArrayNumberListBox.Value;
            [n]=find_index(Items,im);

            Items = char(app.VRSTypeListBox_3.Items);
            im=app.VRSTypeListBox_3.Value;
            [m]=find_index(Items,im);

            if(n==1)
                if(m==1)
                    data=app.accel_vrs_grms_1;
                else
                    data=app.accel_vrs_grms_2;
                end
            else
                if(m==1)
                    data=app.accel_vrs_peak_1;
                else
                    data=app.accel_vrs_peak_2;
                end          
            end    
            
            try
                name=app.VRSOutputArrayNameEditField_2.Value;
                assignin('base',name,data);
                msgbox('Data saved')
            catch
                msgbox('Export failed')
            end
        end

        % Button pushed function: SaveFDSButton_2
        function SaveFDSButton_2Pushed(app, event)
            Items = char(app.ArrayNumberListBox.Items);
            im=app.ArrayNumberListBox.Value;
            [n]=find_index(Items,im);
            
            if(n==1)
                data=app.fds_1;
            else
                data=app.fds_2;            
            end    

            try
                name=app.FDSOutputArrayNameEditField_2.Value;
                assignin('base',name,data);
                msgbox('Data saved');
            catch
                msgbox('Export failed')                
            end    
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            CalculateButton_2Pushed(app, event);
        end

        % Value changed function: RelativeDisplacementUnitListBox
        function RelativeDisplacementUnitListBoxValueChanged(app, event)
            RelDispUnit(app);
            clear(app);
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            parameters(app);
            one_psd(app);

            THF=app.THM;

            n=app.RDU;
            
            disp(' ');
            disp('***************************************************');
            
            [~,app.rms]=calculate_PSD_slopes(THF(:,1),THF(:,2));
            
            if(n==1)
                out1=sprintf(' Overall level = %8.4g inch RMS \n',app.rms);
            else
                out1=sprintf(' Overall level = %8.4g mm RMS \n',app.rms);    
            end
            disp(out1);
            
            Z=str2num(app.Zlimitfor20millioncyclesEditField.Value);
            
            if(n==2)  % mm to inch conversion
                scale=25.4;
                THF(:,2)=THF(:,2)/scale^2;
                Z=Z/scale;
            end
            
            T=app.dur;
            
            [~,amp,EP]=Dirlik_cycles(THF,app.dur);
            
            app.num=length(amp);
            
            rd_Z_ratio = amp/Z;
            
            CDI=0;
            
            for i=1:app.num
                
                cycles=1;
            %
                if(rd_Z_ratio(i)>=6)
                    disp(' Failure:  relative displacement exceeds upper limit. ');
                    warndlg(' Failure:  relative displacement exceeds upper limit. ');
                    return
                end
            %
                N=10^(6.05-6.4*log10(rd_Z_ratio(i)));
            %
                d=(cycles/N);    
                
                CDI=CDI+d;
            end
            
            out44=sprintf(' Max amp = %8.4g \n',max(amp));
            disp(out44);
            
            out00=sprintf(' Max rd_Z_ratio = %8.4g \n',max(rd_Z_ratio));
            disp(out00);
            
            out11=sprintf(' Duration = %g sec   Cycles=%9.5g \n',T,T*EP);
            disp(out11);
            
            out22=sprintf(' CDI = %8.4g \n',CDI);
            disp(out22);

            Ds=sprintf('Cumulative Damage Index = %7.3g \n',CDI);
            
            rate=CDI/T;
            
            out33=sprintf(' Damage Rate = %8.4g per sec',rate);
            disp(out33);

            ss=sprintf('%s \n %s \n',Ds,out33);
            
            tfail=(0.7/rate);
            
            out2=sprintf(' Time to failure (R=0.7) \n\n   = %8.4g sec   Cycles=%9.5g \n',tfail,tfail*EP);
            disp(out2);

            ss=sprintf('%s \n %s',ss,out2);
            
            h=floor(tfail/3600);
            m=floor((tfail-h*3600)/60);
            s=floor( tfail-h*3600-m*60);
            
            string=sprintf('  = %g hr %g min %g sec \n',h,m,s);
            disp(string)
            
            ss=sprintf('%s \n %s',ss,string);

            dsec=3600*24;
            ysec=dsec*365;
            
            y=floor(tfail/ysec);
            d=floor((tfail-y*ysec)/dsec);
            
            h=floor((tfail-y*ysec-d*dsec)/3600);
            m=floor((tfail-y*ysec-d*dsec-h*3600)/60);
            s=floor( tfail-y*ysec-d*dsec-h*3600-m*60);
            
            string=sprintf('   =  %g years %g days %g hr %g min %g sec',y,d,h,m,s);
            disp(string);
     
            ss=sprintf('%s \n %s \n',ss,string);

            app.ResultsTextArea_3.Value=ss;

            % app.CumulativeDamageTextArea.Visible='on';  
            % app.CumulativeDamageTextAreaLabel.Visible='on'; 
        end

        % Value changing function: Zlimitfor20millioncyclesEditField
        function Zlimitfor20millioncyclesEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changed function: SelectUnitsListBox
        function SelectUnitsListBoxValueChanged(app, event)
            SV_Change(app);
        end

        % Value changed function: MaterialListBox_2
        function MaterialListBox_2ValueChanged(app, event)
            SV_Change(app);            
        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            
            fig_num=1;

            parameters(app);
            one_psd(app);           
            
            n=length(app.THM(:,1));
            
            if(app.THM(1,1)<1.0e-04)
                app.THM(1,1)=1.0e-04;
            end
            
            app.f=app.THM(:,1);
            app.a=app.THM(:,2);
            
            [~,arms] = calculate_PSD_slopes(app.f,app.a);
            
            omega=2*pi*app.f;
            %
            
            app.E=app.ElasticModuluspsiEditField_sv.Value;
            app.rho=app.MassDensitylbmin3EditField_sv.Value;
            
            if(app.iu==1)
                app.rho=app.rho/386;
            else
                [app.E]=GPa_to_Pa(app.E);
            end
            
            chat=str2num(app.ProportionalityConstantEditField.Value);
            
            app.E
            app.rho

            Q2=(chat*sqrt(app.E*app.rho))^2
            
            v=zeros(n,1);
            %      
            for i=1:n
                v(i)=app.a(i)/omega(i)^2;
            end
            
            if(app.iu==1)
                v=v*386^2;
                s=v*Q2/1000^2;  % ksi
            else
                v=v*9.81^2;
            end
            
            [~,vrms] = calculate_PSD_slopes(app.f,v);
            [~,srms] = calculate_PSD_slopes(app.f,s);
            
            xlabel2='Frequency (Hz)';
            nfont=11;
            
            data1=[app.f app.a];
            data2=[app.f v];
            
            t_string1=sprintf('Acceleration PSD  %7.3g GRMS Overall',arms);
            
            if(app.iu==1)
                t_string2=sprintf('Velocity PSD  %7.3g (in/sec) RMS Overall',vrms);
                ylabel2='Vel ((in/sec)^2/Hz))';
            else
                t_string2=sprintf('Velocity PSD  %7.3g (m/sec) RMS Overall',vrms);
                ylabel2='Vel ((m/sec)^2/Hz))';
            end

            ylabel1='Accel (G^2/Hz)';
            
            app.fmin=app.THM(1,1);
            app.fmax=app.THM(end,1);
            
            [fig_num,h2]=subplots_two_loglog_1x2_xlim_h2(fig_num,xlabel2,ylabel1,ylabel2,data1,data2,t_string1,t_string2,nfont,app.fmin,app.fmax);
            
            set(h2, 'Position', [0 0 1000 400]);
            
            %%
            
            md=6;

            x_label='Frequency (Hz)';
            
            if(app.iu==1)
                t_string=sprintf('Stress PSD  %7.3g ksi RMS Overall',srms);
                y_label='Stress (ksi^2/Hz)';
            else
                t_string=sprintf('Stress PSD  %7.3g Pa RMS Overall',srms);
                y_label='Stress (Pa^2/Hz)';
            end

            ppp=[app.f s];
            [fig_num]=plot_loglog_function_md(fig_num,x_label,y_label,t_string,ppp,app.fmin,app.fmax,md);
           
            velocity_psd=[app.f v];
            stress_psd=[app.f s];

            try
                name=app.VelocityResponsePSDEditField.Value;
                assignin('base',name,velocity_psd);
            catch
            end

            try
                name=app.StressResponsePSDEditField.Value;
                assignin('base',name,stress_psd);
            catch
            end

            update_alt(app);
            
        end

        % Value changed function: PerformRelativeFatigueAnalysisListBox
        function PerformRelativeFatigueAnalysisListBoxValueChanged(app, event)
            single_fn_relative_damage(app);
        end

        % Button pushed function: ViewEquationButton
        function ViewEquationButtonPushed(app, event)
            A = imread('nasgro_coefficients.jpg');
            figure(100);
            imshow(A,'border','tight','InitialMagnification',100);
        end

        % Button pushed function: CalculateButton_CDI
        function CalculateButton_CDIPushed(app, event)
            ClosePlots(app);
            parameters(app);     
            one_psd(app);
            
            Items = char(app.StressUnitListBox.Items);
            im=app.StressUnitListBox.Value;
            [niu]=find_index(Items,im);

            scf=str2num(app.StressScaleFactorEditField.Value);
            R=str2num(app.StressRatioEditField.Value);

            A=str2num(app.AEditField.Value);
            B=str2num(app.BEditField.Value);
            C=str2num(app.CEditField.Value);
            P=str2num(app.PEditField.Value);            

            tau=str2num(app.DurationsecEditField.Value);

            if(B<0)
                warndlg(' B coefficient should be > 0 '); 
                return;
            end
            
            disp(' ');
            disp('***************************************************');
            
            fprintf('\n PSD filename:  %s \n\n',app.FS);
            
            sz=size(app.THM);
            
            if(niu==1) % psi to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/1000^2;
            end
            if(niu==3) % Pa to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/( 6891^2  );
            end
            if(niu==4) % MPa to ksi
                app.THM(:,2:end)=app.THM(:,2:end)/(( 6891e-06  )^2);
            end
            
            [app.f,app.a,s,app.rms,app.df,app.THM,app.fi,app.ai]=fatigue_psd_check(app.THM,scf);
            
            fig_num=1;
            
            xlab='Frequency (Hz)';
            
            sss=sprintf(' %8.4g ksi RMS ',app.rms);  
            t_string=sprintf('Stress PSD   %s  stress scale factor=%g',sss,scf);
             
            ylab='Stress (ksi^2/Hz)';
            out1=sprintf('PSD unit:  %s ',ylab);
            disp(out1);
             
            md=5; 
            
            app.fmin=app.THM(1,1);
            app.fmax=app.THM(end,1);
            
            [fig_num,h2]=...
                plot_loglog_function_md_h2(fig_num,xlab,ylab,t_string,app.THM,app.fmin,app.fmax,md);
            
            sigma_s=app.rms;
             
            clear length;
            n=length(app.fi);
            %
                        
            [m0,m1,m2,m4,M2m,Mkp2,m0p75,m1p5,vo,EP,alpha2,e,D1,D2,D3,QQ,Rd]=...
                                                 spectral_moments_nasgro(n,B,app.ai,app.fi,app.df);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Note that range = (peak-valley)
            %
            %  maxS is the assumed upper limit of the the histogram.
            %
            %  The value 6*grms is used as conservative estimate of the upper
            %  range maxS for all cycles.  
            %
            %  The histogram will have 400 bins.  This number is chosen
            %  via engineering judgement.
            %
            %    ds is the bin range width       
            %     n is the number of bins
            %     N is the cycle count per bin
            %     S is the range level for each bin
            %
            app.rms=sqrt(m0);
            
            maxS=8*app.rms;  
            %
            ds=maxS/800;
            %
            n=round(maxS/ds);
            %
            EP_tau=EP*tau;
            
            %
            %   Dirlik
            %
            
            disp(' ');
            
            out1=sprintf(' n=%d  m0=%8.4g  ds=%8.4g  Rd=%8.4g   ',n,m0,ds,Rd);
            disp(out1);
            disp(' ');
            
            out1=sprintf(' D1=%8.3g  D2=%8.3g  D3=%8.3g  Q=%8.3g   Rd=%8.3g ',D1,D2,D3,QQ,Rd);
            disp(out1);
            disp(' ');
            
            out1=sprintf(' D1/Q=%8.3g  D2/R^2=%8.3g  D3=%8.3g  ',D1/QQ,D2/R^2,D3);
            disp(out1);
            disp(' ');
            
            [Dirlik_N,range,dz]=Dirlik_nasgro_probability(n,m0,QQ,D1,D2,D3,ds,Rd);
            
            disp(' ');
            out1=sprintf('*** max DN=%8.4g   min DN=%8.4g ',max(Dirlik_N),min(Dirlik_N));
            disp(out1);
            
            disp(' ');
            out1=sprintf('*** max Range=%8.4g   min Range=%8.4g ',max(range),min(range));
            disp(out1);
            
            DN=Dirlik_N/sum(Dirlik_N);
            
            aaaa=(range/2); 
            bbbb=DN*EP_tau/ds;
            
            size(aaaa);
            size(bbbb);
            
            assignin('base', 'Dirlik_N_with_scf',[ (range/2)  DN*EP_tau/ds]);
            assignin('base', 'Dirlik_N_without_scf',[ (range/2)/scf  DN*EP_tau/ds]);
            
            out1=sprintf('n=%d A=%8.4g B=%8.4g C=%8.4g P=%8.4g R=%8.4g ',n,A,B,C,P,R);
            disp(out1);
            
            out1=sprintf(' EP_tau=%8.4g  dz=%8.4g ',EP_tau,dz);
            disp(out1);
            
            
            [Dirlik_damage]=Dirlik_nasgro(n,A,B,C,P,R,EP_tau,Dirlik_N,range,dz);
            
            out1=sprintf(' Dirlik_damage=%8.4g ',Dirlik_damage);
            disp(out1);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %  Narrowband
            %
            
            D1=0;
            D2=0;
            D3=1;
            
            [Narrowband_N,range,dz]=Dirlik_nasgro_probability(n,m0,QQ,D1,D2,D3,ds,Rd);
            
            vo_tau=vo*tau;  % important for narrowband
            
            [Narrowband_damage]=Dirlik_nasgro(n,A,B,C,P,R,vo_tau,Narrowband_N,range,dz);
            
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            disp(' '); 
            disp(' * * * * ');
            disp(' ');
             
            out1=sprintf('Material: %s ',app.mlab);
            disp(out1);
            ss=out1;  
             
            out1=sprintf('   A=%g  B=%g  C=%g  P=%g ',A,B,C,P);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);
            
            out1=sprintf('\n m0=%8.4g \t m1=%8.4g \t m2=%8.4g \t m4=%8.4g ',m0,m1,m2,m4);
            disp(out1);
            ss=sprintf('%s %s',ss,out1); 
             
            out1=sprintf('\n ds=%8.4g dz=%8.4g',ds,dz);
            disp(out1);
            
            out1=sprintf('  Duration=%g sec \n\n',tau);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);
              
            out1=sprintf('    v0+=%8.4g Hz, rate of zero upcrossings  \t ',vo);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);
            out1=sprintf('  EP=%8.4g Hz, expected peak rate \n',EP);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);
            out1=sprintf(' alpha2=%8.4g ',alpha2);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);
            out1=sprintf('      e=%8.4g spectral width \n',e);
            disp(out1);
            ss=sprintf('%s %s',ss,out1);       
             
            avtt=vo*tau;
            c=sqrt(2*log(avtt));
            ps=c + 0.5772/c;
             
            out1=sprintf('\nStress Ratio R = %g',R);
            disp(out1); 
            ss=sprintf('%s %s',ss,out1);
            
            out1=sprintf('\t Stress Scale Factor = %g',scf);
            disp(out1);
            ss=sprintf('%s %s\n',ss,out1);
             
            out1=sprintf('Scaled Overall Stress Level= %7.3g ksi rms ',sqrt(m0));
            disp(out1);
            ss=sprintf('%s \n%s',ss,out1);
             
            disp(' ');
            out1=sprintf(' with Max expected peak: %7.3g ksi (%4.3g sigma) \n',ps*app.rms,ps);
            disp(out1);
            ss=sprintf('%s\t%s',ss,out1);
             
            DNB=Narrowband_damage;
            
            m=B;
            
            lambda_ll=M2m^(m/2)/(vo*sigma_s^m);
            LL_damage=DNB*lambda_ll;
            
            alpha_0p75=m0p75/sqrt(m0*m1p5);
            
            DAL=alpha_0p75^2*DNB;
            
            disp(' ');
            disp('    Accumulated Damage ');
             ss=sprintf('%s\nAccumalated Damage \t',ss);
            
            out1=sprintf('Dirlik = %7.3g',Dirlik_damage);
            out2=sprintf('Single-Moment = %7.3g',LL_damage);
            out3=sprintf('alpha_0p75 = %7.3g',DAL);
            disp(out1);
            disp(out2);
            disp(out3);
            ss=sprintf('%s %s \t %s \t %s',ss,out1,out2,out3);
            
            disp(' ');
            disp('    Damage Rate per Sec ');
            
            out1=sprintf('Dirlik = %7.3g ',Dirlik_damage/tau);
            out2=sprintf('Single-Moment = %7.3g ',LL_damage/tau);
            out3=sprintf('alpha_0p75 = %7.3g ',DAL/tau);
            disp(out1);
            disp(out2);
            disp(out3);
            ss=sprintf('%s\nDamage Rate per Sec \t',ss);
            ss=sprintf('%s %s \t %s \t %s',ss,out1,out2,out3);
            
            disp(' ');
            disp('    Time to Failure for Cumulative Damage Index=1');
            
            tfail=tau/Dirlik_damage;
            d=floor(tfail/86400);
            trem=tfail-d*86400;
            h=floor(trem/3600);
            trem=trem-h*3600;
            m=floor(trem/60);
            trem=trem-m*60;
            s=floor(trem);
            
            out1=sprintf('\t Dirlik = %8.4g sec ',tfail );
            disp(out1);
            ss=sprintf('%s\n\nTime to Failure for CDI=1 %s ',ss,out1);
            
            string=sprintf('  (%g days %g hr %g min %g sec) \n',d,h,m,s);
            disp(string)
            ss=sprintf('%s\t%s',ss,string);
            
            
            disp('    Time to Failure for Cumulative Damage Index=0.1');
            
            tfail=tau/Dirlik_damage/10;
            d=floor(tfail/86400);
            trem=tfail-d*86400;
            h=floor(trem/3600);
            trem=trem-h*3600;
            m=floor(trem/60);
            trem=trem-m*60;
            s=floor(trem);
            out1=sprintf('\t Dirlik = %8.4g sec ',tfail );
            ss=sprintf('%sTime to Failure for CDI=0.1 %s ',ss,out1);
            
            string=sprintf('  (%g days %g hr %g min %g sec) \n',d,h,m,s);
            disp(string)
            ss=sprintf('%s\t%s',ss,string);
            
            app.CumulativeDamageIndexResultsTextArea.Value=ss;
            app.CumulativeDamageIndexResultsTextArea.Visible='on';           
            app.CumulativeDamageIndexResultsTextAreaLabel.Visible='on';      

        end

        % Value changed function: StressUnitListBox
        function StressUnitListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: MaterialLibraryListBox
        function MaterialLibraryListBoxValueChanged(app, event)
            clear(app);
            materials(app);
        end

        % Value changing function: StressScaleFactorEditField
        function StressScaleFactorEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: StressRatioEditField
        function StressRatioEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AEditField
        function AEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: BEditField
        function BEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: CEditField
        function CEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: PEditField
        function PEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField_sfn
        function AmplificationFactorQEditField_sfnValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function AmplificationFactorQEditField_3ValueChanging(app, event)
            clear(app);
        end

        % Value changed function: PerformFatigueAnalysisListBox
        function PerformFatigueAnalysisListBoxValueChanged2(app, event)
            VRS_FDS(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed2(app, event)
  
            Items = char(app.ExporttoMatlabWorkspaceListBox.Items);
            im=app.ExporttoMatlabWorkspaceListBox.Value;
            [n]=find_index(Items,im);

            name=app.OutputArrayNameEditField.Value;
            
            if(n==1)
                data=app.accel_response_psd;
            end            
            if(n==2)
                data=app.rd_response_psd;
            end
            if(n==3)
                data=app.accel_power_trans;
            end


            try
                assignin('base',name,data);
                msgbox('Array saved');
            catch
                warndlg('Save failed');
            end

        end

        % Button pushed function: UpdateListButton_2
        function UpdateListButton_2Pushed2(app, event)
            update_2(app);
        end

        % Value changed function: NumberofQCasesListBox
        function NumberofQCasesListBoxValueChanged(app, event)

            ChangeQ(app);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VRSFDSBaseUIFigure = uifigure('Visible', 'off');
            app.VRSFDSBaseUIFigure.Name = 'VRS FDS Base';
            app.ReturnButton = uibutton(app.VRSFDSBaseUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.vrs_fds_basever10byTomIrvineLabel = uilabel(app.VRSFDSBaseUIFigure);
            app.vrs_fds_basever10byTomIrvineLabel.Text = 'vrs_fds_base     ver 1.9   by Tom Irvine      This script calculates the response of an SDOF system to a base input power spectral density. ';
            app.Label = uilabel(app.VRSFDSBaseUIFigure);
            app.Label.Text = 'The fatigue damage spectrum (FDS) may also be calculated.    The input array must have two columns: freq(Hz) & accel(unit^2/Hz)';
            app.TabGroup = uitabgroup(app.VRSFDSBaseUIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.IntroTab.Tag = 'VRS FDS Base';
            app.Image = uiimage(app.IntroTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sdof_base.jpg');
            app.PlotFrequenciesLabel = uilabel(app.IntroTab);
            app.PlotFrequenciesLabel.Text = 'Plot Frequencies';
            app.MinFreqHzEditFieldLabel = uilabel(app.IntroTab);
            app.MinFreqHzEditFieldLabel.Text = 'Min Freq (Hz)';
            app.MinFreqHzEditField = uieditfield(app.IntroTab, 'text');
            app.MinFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MinFreqHzEditFieldValueChanging, true);
            app.MinFreqHzEditField.Value = '20';
            app.MaxFreqHzEditFieldLabel = uilabel(app.IntroTab);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq (Hz)';
            app.MaxFreqHzEditField = uieditfield(app.IntroTab, 'text');
            app.MaxFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @MaxFreqHzEditFieldValueChanging, true);
            app.MaxFreqHzEditField.Value = '2000';
            app.OuputFrequenciesListBoxLabel = uilabel(app.IntroTab);
            app.OuputFrequenciesListBoxLabel.Text = 'Ouput Frequencies';
            app.OuputFrequenciesListBox = uilistbox(app.IntroTab);
            app.OuputFrequenciesListBox.Items = {'Constant 0.25 Hz', 'Constant 0.5 Hz', '1/6 octave', '1/12 octave', '1/24 octave', '1/48 octave'};
            app.OuputFrequenciesListBox.ValueChangedFcn = createCallbackFcn(app, @OuputFrequenciesListBoxValueChanged, true);
            app.OuputFrequenciesListBox.Value = 'Constant 0.5 Hz';
            app.AmplitudeUnitsListBoxLabel = uilabel(app.IntroTab);
            app.AmplitudeUnitsListBoxLabel.Text = 'Amplitude Units';
            app.AmplitudeUnitsListBox = uilistbox(app.IntroTab);
            app.AmplitudeUnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm', 'm/sec^2, m/sec, mm'};
            app.AmplitudeUnitsListBox.Value = 'G, in/sec, in';
            app.OnePSDTab = uitab(app.TabGroup);
            app.OnePSDTab.Title = 'One PSD';
            app.listbox_method = uilistbox(app.OnePSDTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.OnePSDTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.OnePSDTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.OnePSDTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.OnePSDTab);
            app.SelectInputArrayListBox.Items = {};
            app.SelectInputArrayListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.OnePSDTab);
            app.GivenfnTab = uitab(app.TabGroup2);
            app.GivenfnTab.Title = 'Given fn';
            app.TabGroup5 = uitabgroup(app.GivenfnTab);
            app.ResponsePSDTab = uitab(app.TabGroup5);
            app.ResponsePSDTab.Title = 'Response PSD';
            app.NaturalFrequencyHzLabel = uilabel(app.ResponsePSDTab);
            app.NaturalFrequencyHzLabel.Text = {'Natural '; 'Frequency (Hz)'};
            app.NaturalFrequencyHzEditField = uieditfield(app.ResponsePSDTab, 'text');
            app.NaturalFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @NaturalFrequencyHzEditFieldValueChanging, true);
            app.CalculateButton_3 = uibutton(app.ResponsePSDTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ResultsTextArea_2Label = uilabel(app.ResponsePSDTab);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.ResultsTextArea_sfn = uitextarea(app.ResponsePSDTab);
            app.ResultsTextArea_sfn.Editable = 'off';
            app.AmplificationFactorQEditField_sfnLabel = uilabel(app.ResponsePSDTab);
            app.AmplificationFactorQEditField_sfnLabel.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_sfn = uieditfield(app.ResponsePSDTab, 'text');
            app.AmplificationFactorQEditField_sfn.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditField_sfnValueChanging, true);
            app.AmplificationFactorQEditField_sfn.Value = '10';
            app.FatigueTypeListBox_sfnLabel = uilabel(app.ResponsePSDTab);
            app.FatigueTypeListBox_sfnLabel.Text = 'Fatigue Type';
            app.FatigueTypeListBox_sfn = uilistbox(app.ResponsePSDTab);
            app.FatigueTypeListBox_sfn.Items = {'Acceleration', 'Pseudo Velocity', 'Relative Disp'};
            app.FatigueTypeListBox_sfn.Value = 'Acceleration';
            app.PerformRelativeFatigueAnalysisListBoxLabel = uilabel(app.ResponsePSDTab);
            app.PerformRelativeFatigueAnalysisListBoxLabel.Text = 'Perform Relative Fatigue Analysis';
            app.PerformRelativeFatigueAnalysisListBox = uilistbox(app.ResponsePSDTab);
            app.PerformRelativeFatigueAnalysisListBox.Items = {'No', 'Yes'};
            app.PerformRelativeFatigueAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @PerformRelativeFatigueAnalysisListBoxValueChanged, true);
            app.PerformRelativeFatigueAnalysisListBox.Value = 'No';
            app.FatigueExponentEditField_sfnLabel = uilabel(app.ResponsePSDTab);
            app.FatigueExponentEditField_sfnLabel.Text = 'Fatigue Exponent';
            app.FatigueExponentEditField_sfn = uieditfield(app.ResponsePSDTab, 'text');
            app.FatigueExponentEditField_sfn.Value = '4';
            app.SaveResponsePSDTab = uitab(app.TabGroup5);
            app.SaveResponsePSDTab.Title = 'Save Response PSD';
            app.SaveButton = uibutton(app.SaveResponsePSDTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed2, true);
            app.SaveButton.Text = 'Save';
            app.ExporttoMatlabWorkspaceListBoxLabel = uilabel(app.SaveResponsePSDTab);
            app.ExporttoMatlabWorkspaceListBoxLabel.Text = 'Export to Matlab Workspace';
            app.ExporttoMatlabWorkspaceListBox = uilistbox(app.SaveResponsePSDTab);
            app.ExporttoMatlabWorkspaceListBox.Items = {'Acceleration PSD', 'Relative Displacement PSD', 'Acceleration Power Trans'};
            app.ExporttoMatlabWorkspaceListBox.Value = 'Acceleration PSD';
            app.OutputArrayNameEditFieldLabel = uilabel(app.SaveResponsePSDTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.SaveResponsePSDTab, 'text');
            app.StressVelocityPSDTab = uitab(app.TabGroup5);
            app.StressVelocityPSDTab.Title = 'Stress-Velocity PSD';
            app.CalculateButton_6 = uibutton(app.StressVelocityPSDTab, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.ElasticModuluspsiEditFieldLabel_sv = uilabel(app.StressVelocityPSDTab);
            app.ElasticModuluspsiEditFieldLabel_sv.Text = {'Elastic '; 'Modulus (psi)'};
            app.ElasticModuluspsiEditField_sv = uieditfield(app.StressVelocityPSDTab, 'numeric');
            app.MassDensitylbmin3EditField_svLabel = uilabel(app.StressVelocityPSDTab);
            app.MassDensitylbmin3EditField_svLabel.Text = {'Mass Density '; '(lbm/in^3)'};
            app.MassDensitylbmin3EditField_sv = uieditfield(app.StressVelocityPSDTab, 'numeric');
            app.SelectUnitsListBoxLabel = uilabel(app.StressVelocityPSDTab);
            app.SelectUnitsListBoxLabel.Text = 'Select Units';
            app.SelectUnitsListBox = uilistbox(app.StressVelocityPSDTab);
            app.SelectUnitsListBox.Items = {'English', 'metric'};
            app.SelectUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectUnitsListBoxValueChanged, true);
            app.SelectUnitsListBox.Value = 'English';
            app.ProportionalityConstantLabel = uilabel(app.StressVelocityPSDTab);
            app.ProportionalityConstantLabel.Text = {'Proportionality '; 'Constant'};
            app.ProportionalityConstantEditField = uieditfield(app.StressVelocityPSDTab, 'text');
            app.ProportionalityConstantEditField.Value = '4';
            app.Label_4 = uilabel(app.StressVelocityPSDTab);
            app.Label_4.Text = {'The PSD must be an SDOF response PSD '; 'array with two columns:  '; ''; 'freq (Hz) & accel (G^2/Hz)     '; ''; 'Select input array from listbox on the left.'};
            app.MaterialListBox_2Label = uilabel(app.StressVelocityPSDTab);
            app.MaterialListBox_2Label.Text = 'Material';
            app.MaterialListBox_2 = uilistbox(app.StressVelocityPSDTab);
            app.MaterialListBox_2.Items = {'Aluminum', 'Steel', 'Copper', 'G10', 'Titanium', 'Graphite/Epoxy', 'Other'};
            app.MaterialListBox_2.ValueChangedFcn = createCallbackFcn(app, @MaterialListBox_2ValueChanged, true);
            app.MaterialListBox_2.Value = 'Aluminum';
            app.VelocityResponsePSDEditFieldLabel = uilabel(app.StressVelocityPSDTab);
            app.VelocityResponsePSDEditFieldLabel.Text = 'Velocity Response PSD';
            app.VelocityResponsePSDEditField = uieditfield(app.StressVelocityPSDTab, 'text');
            app.VelocityResponsePSDEditField.Value = 'velocity_response_PSD';
            app.OutputArrayNamesLabel = uilabel(app.StressVelocityPSDTab);
            app.OutputArrayNamesLabel.Text = 'Output Array Names';
            app.StressResponsePSDEditFieldLabel = uilabel(app.StressVelocityPSDTab);
            app.StressResponsePSDEditFieldLabel.Text = 'Stress Response PSD';
            app.StressResponsePSDEditField = uieditfield(app.StressVelocityPSDTab, 'text');
            app.StressResponsePSDEditField.Value = 'stress_response_PSD';
            app.StressPSDDirlikFatigueCDITab = uitab(app.TabGroup5);
            app.StressPSDDirlikFatigueCDITab.Title = 'Stress PSD: Dirlik Fatigue CDI';
            app.TabGroup6 = uitabgroup(app.StressPSDDirlikFatigueCDITab);
            app.EnterDataTab = uitab(app.TabGroup6);
            app.EnterDataTab.Title = 'Enter Data';
            app.StressScaleFactorEditFieldLabel = uilabel(app.EnterDataTab);
            app.StressScaleFactorEditFieldLabel.Text = 'Stress Scale Factor';
            app.StressScaleFactorEditField = uieditfield(app.EnterDataTab, 'text');
            app.StressScaleFactorEditField.ValueChangingFcn = createCallbackFcn(app, @StressScaleFactorEditFieldValueChanging, true);
            app.StressScaleFactorEditField.Value = '1';
            app.StressRatioEditFieldLabel = uilabel(app.EnterDataTab);
            app.StressRatioEditFieldLabel.Text = 'Stress Ratio';
            app.StressRatioEditField = uieditfield(app.EnterDataTab, 'text');
            app.StressRatioEditField.ValueChangingFcn = createCallbackFcn(app, @StressRatioEditFieldValueChanging, true);
            app.StressRatioEditField.Value = '-1';
            app.MaterialLibraryListBoxLabel = uilabel(app.EnterDataTab);
            app.MaterialLibraryListBoxLabel.Text = 'Material Library';
            app.MaterialLibraryListBox = uilistbox(app.EnterDataTab);
            app.MaterialLibraryListBox.Items = {'Aluminum 6061-T6, Kt=1.0', 'Aluminum 7050-T7451, Kt=1.0', 'Aluminum 7075-T6, Kt=1.0', 'Inconel 718, Kt=1.0', 'Stainless Steel Custom 450 (H1050), Kt=3.0', 'Stainless Steel 15-5PH 1025, Kt=3.0', 'Other  '};
            app.MaterialLibraryListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialLibraryListBoxValueChanged, true);
            app.MaterialLibraryListBox.Value = 'Aluminum 6061-T6, Kt=1.0';
            app.Label_5 = uilabel(app.EnterDataTab);
            app.Label_5.Text = {'The PSD must be a stress response '; 'PSD array with two columns:  '; ''; 'freq (Hz) & stress (units^2/Hz)     '; ''; 'Select input array from listbox on '; 'the left.'};
            app.StressUnitListBoxLabel = uilabel(app.EnterDataTab);
            app.StressUnitListBoxLabel.Text = 'Stress Unit';
            app.StressUnitListBox = uilistbox(app.EnterDataTab);
            app.StressUnitListBox.Items = {'psi', 'ksi', 'Pa', 'MPa'};
            app.StressUnitListBox.ValueChangedFcn = createCallbackFcn(app, @StressUnitListBoxValueChanged, true);
            app.StressUnitListBox.Value = 'ksi';
            app.Label_7 = uilabel(app.EnterDataTab);
            app.Label_7.Text = {'MMPDS, NASGRO '; 'S/N Curve Coefficients'};
            app.RSminSmaxR1forfullyreversedloadingR1meantensilestressLabel = uilabel(app.EnterDataTab);
            app.RSminSmaxR1forfullyreversedloadingR1meantensilestressLabel.Text = {'R = Smin/Smax  '; 'R = -1 for fully reversed loading  '; 'R > -1  mean tensile stress'};
            app.AEditFieldLabel = uilabel(app.EnterDataTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.EnterDataTab, 'text');
            app.AEditField.ValueChangingFcn = createCallbackFcn(app, @AEditFieldValueChanging, true);
            app.BEditFieldLabel = uilabel(app.EnterDataTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.EnterDataTab, 'text');
            app.BEditField.ValueChangingFcn = createCallbackFcn(app, @BEditFieldValueChanging, true);
            app.CEditFieldLabel = uilabel(app.EnterDataTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.EnterDataTab, 'text');
            app.CEditField.ValueChangingFcn = createCallbackFcn(app, @CEditFieldValueChanging, true);
            app.PEditFieldLabel = uilabel(app.EnterDataTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.EnterDataTab, 'text');
            app.PEditField.ValueChangingFcn = createCallbackFcn(app, @PEditFieldValueChanging, true);
            app.ViewEquationButton = uibutton(app.EnterDataTab, 'push');
            app.ViewEquationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEquationButtonPushed, true);
            app.ViewEquationButton.Text = 'View Equation';
            app.CalculateViewResultsTab = uitab(app.TabGroup6);
            app.CalculateViewResultsTab.Title = 'Calculate & View Results';
            app.CalculateButton_CDI = uibutton(app.CalculateViewResultsTab, 'push');
            app.CalculateButton_CDI.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_CDIPushed, true);
            app.CalculateButton_CDI.Text = 'Calculate';
            app.CumulativeDamageIndexResultsTextAreaLabel = uilabel(app.CalculateViewResultsTab);
            app.CumulativeDamageIndexResultsTextAreaLabel.Text = 'Cumulative Damage Index Results';
            app.CumulativeDamageIndexResultsTextArea = uitextarea(app.CalculateViewResultsTab);
            app.SteinbergFatigueTab_2 = uitab(app.TabGroup5);
            app.SteinbergFatigueTab_2.Title = 'Steinberg Fatigue';
            app.ResultsTextArea_3Label = uilabel(app.SteinbergFatigueTab_2);
            app.ResultsTextArea_3Label.Text = 'Results';
            app.ResultsTextArea_3 = uitextarea(app.SteinbergFatigueTab_2);
            app.inch3sigmaLabel = uilabel(app.SteinbergFatigueTab_2);
            app.inch3sigmaLabel.Text = 'inch (3-sigma)';
            app.Zlimitfor20millioncyclesEditFieldLabel = uilabel(app.SteinbergFatigueTab_2);
            app.Zlimitfor20millioncyclesEditFieldLabel.Text = 'Z limit for 20 million cycles';
            app.Zlimitfor20millioncyclesEditField = uieditfield(app.SteinbergFatigueTab_2, 'text');
            app.Zlimitfor20millioncyclesEditField.ValueChangingFcn = createCallbackFcn(app, @Zlimitfor20millioncyclesEditFieldValueChanging, true);
            app.RelativeDisplacementUnitListBoxLabel = uilabel(app.SteinbergFatigueTab_2);
            app.RelativeDisplacementUnitListBoxLabel.Text = {'Relative '; 'Displacement '; 'Unit'};
            app.RelativeDisplacementUnitListBox = uilistbox(app.SteinbergFatigueTab_2);
            app.RelativeDisplacementUnitListBox.Items = {'inch', 'mm'};
            app.RelativeDisplacementUnitListBox.ValueChangedFcn = createCallbackFcn(app, @RelativeDisplacementUnitListBoxValueChanged, true);
            app.RelativeDisplacementUnitListBox.Value = 'inch';
            app.CalculateButton_5 = uibutton(app.SteinbergFatigueTab_2, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.Label_3 = uilabel(app.SteinbergFatigueTab_2);
            app.Label_3.Text = {'This script calculates the Palmgren-Miner cumulative fatigue damage from a relative displacement PSD '; 'for an electronic circuit board, using an extension of Steinberg''s empirical data.    '; ''; 'The input PSD  array must have two columns:  freq (Hz) & rel disp (unit^2/Hz)     '; ''; 'Select input array from listbox on the left.'};
            app.VRSFDSTab = uitab(app.TabGroup2);
            app.VRSFDSTab.Title = 'VRS & FDS';
            app.TabGroup4 = uitabgroup(app.VRSFDSTab);
            app.CalculateTab = uitab(app.TabGroup4);
            app.CalculateTab.Title = 'Calculate';
            app.ResultsTextAreaLabel = uilabel(app.CalculateTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.CalculateTab);
            app.ResultsTextArea.Editable = 'off';
            app.CalculateButton_one_PSD_VRS = uibutton(app.CalculateTab, 'push');
            app.CalculateButton_one_PSD_VRS.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_one_PSD_VRSPushed, true);
            app.CalculateButton_one_PSD_VRS.Text = 'Calculate';
            app.UITable_Q = uitable(app.CalculateTab);
            app.UITable_Q.ColumnName = {'Q'};
            app.UITable_Q.RowName = {};
            app.UITable_Q.ColumnEditable = true;
            app.NumberofQCasesLabel = uilabel(app.CalculateTab);
            app.NumberofQCasesLabel.Text = {'Number of '; 'Q Cases'};
            app.NumberofQCasesListBox = uilistbox(app.CalculateTab);
            app.NumberofQCasesListBox.Items = {'1', '2', '3'};
            app.NumberofQCasesListBox.ValueChangedFcn = createCallbackFcn(app, @NumberofQCasesListBoxValueChanged, true);
            app.NumberofQCasesListBox.Value = '1';
            app.ExportTab_3 = uitab(app.TabGroup4);
            app.ExportTab_3.Title = 'Export';
            app.SaveVRSButton = uibutton(app.ExportTab_3, 'push');
            app.SaveVRSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVRSButtonPushed, true);
            app.SaveVRSButton.Text = 'Save VRS';
            app.VRSTypeListBox = uilistbox(app.ExportTab_3);
            app.VRSTypeListBox.Items = {'1-sigma', '3-sigma', 'peak'};
            app.VRSTypeListBox.Value = '1-sigma';
            app.VRSOutputArrayNameEditFieldLabel = uilabel(app.ExportTab_3);
            app.VRSOutputArrayNameEditFieldLabel.Text = 'VRS Output Array Name';
            app.VRSOutputArrayNameEditField = uieditfield(app.ExportTab_3, 'text');
            app.VRSTypeListBox_2Label = uilabel(app.ExportTab_3);
            app.VRSTypeListBox_2Label.Text = 'VRS Type';
            app.VRSTypeListBox_2 = uilistbox(app.ExportTab_3);
            app.VRSTypeListBox_2.Items = {'Acceleration', 'Pseudo Velocity', 'Relative Disp'};
            app.VRSTypeListBox_2.Value = 'Acceleration';
            app.SaveFDSButton = uibutton(app.ExportTab_3, 'push');
            app.SaveFDSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFDSButtonPushed, true);
            app.SaveFDSButton.Text = 'Save FDS';
            app.FDSOutputArrayNameEditFieldLabel = uilabel(app.ExportTab_3);
            app.FDSOutputArrayNameEditFieldLabel.Text = 'FDS Output Array Name';
            app.FDSOutputArrayNameEditField = uieditfield(app.ExportTab_3, 'text');
            app.QvaluewillbeappendedtooutputarraynamesLabel = uilabel(app.ExportTab_3);
            app.QvaluewillbeappendedtooutputarraynamesLabel.Text = 'Q value will be appended to output array names.';
            app.DirlikMethodLabel = uilabel(app.VRSFDSTab);
            app.DirlikMethodLabel.Text = 'Dirlik Method';
            app.FatigueTypeListBoxLabel = uilabel(app.VRSFDSTab);
            app.FatigueTypeListBoxLabel.Text = 'Fatigue Type';
            app.FatigueTypeListBox = uilistbox(app.VRSFDSTab);
            app.FatigueTypeListBox.Items = {'Acceleration', 'Pseudo Velocity', 'Relative Disp'};
            app.FatigueTypeListBox.Value = 'Acceleration';
            app.PerformFatigueAnalysisListBoxLabel = uilabel(app.VRSFDSTab);
            app.PerformFatigueAnalysisListBoxLabel.Text = 'Perform Fatigue Analysis';
            app.PerformFatigueAnalysisListBox = uilistbox(app.VRSFDSTab);
            app.PerformFatigueAnalysisListBox.Items = {'No', 'Yes'};
            app.PerformFatigueAnalysisListBox.ValueChangedFcn = createCallbackFcn(app, @PerformFatigueAnalysisListBoxValueChanged2, true);
            app.PerformFatigueAnalysisListBox.Value = 'No';
            app.FatigueExponentEditFieldLabel = uilabel(app.VRSFDSTab);
            app.FatigueExponentEditFieldLabel.Text = 'Fatigue Exponent';
            app.FatigueExponentEditField = uieditfield(app.VRSFDSTab, 'text');
            app.FatigueExponentEditField.Value = '4';
            app.DurationsecLabel = uilabel(app.OnePSDTab);
            app.DurationsecLabel.Text = {'Duration '; '(sec)'};
            app.DurationsecEditField = uieditfield(app.OnePSDTab, 'text');
            app.DurationsecEditField.Value = '60';
            app.Label_6 = uilabel(app.OnePSDTab);
            app.Label_6.Text = {'(Duration affects maximum peak '; '& fatigue damage only)'};
            app.TwoPSDsTab = uitab(app.TabGroup);
            app.TwoPSDsTab.Title = 'Two PSDs';
            app.TabGroup3 = uitabgroup(app.TwoPSDsTab);
            app.VRSFDSTab_2 = uitab(app.TabGroup3);
            app.VRSFDSTab_2.Title = 'VRS & FDS';
            app.CalculateButton_4 = uibutton(app.VRSFDSTab_2, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.listbox_method_2 = uilistbox(app.VRSFDSTab_2);
            app.listbox_method_2.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method_2.Tag = 'listbox_method';
            app.listbox_method_2.Value = 'Array Preloaded in Matlab';
            app.text1_2 = uilabel(app.VRSFDSTab_2);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Select Data Input Method';
            app.UpdateListButton_2 = uibutton(app.VRSFDSTab_2, 'push');
            app.UpdateListButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButton_2Pushed2, true);
            app.UpdateListButton_2.Text = 'Update List';
            app.SelectInputArray1ListBoxLabel = uilabel(app.VRSFDSTab_2);
            app.SelectInputArray1ListBoxLabel.Text = 'Select Input Array 1';
            app.SelectInputArray1ListBox = uilistbox(app.VRSFDSTab_2);
            app.SelectInputArray1ListBox.Items = {};
            app.SelectInputArray1ListBox.Value = {};
            app.Legend1EditFieldLabel = uilabel(app.VRSFDSTab_2);
            app.Legend1EditFieldLabel.Text = 'Legend 1';
            app.Legend1EditField = uieditfield(app.VRSFDSTab_2, 'text');
            app.Duration1secEditFieldLabel = uilabel(app.VRSFDSTab_2);
            app.Duration1secEditFieldLabel.Text = 'Duration 1 (sec)';
            app.Duration1secEditField = uieditfield(app.VRSFDSTab_2, 'text');
            app.SelectInputArray2ListBoxLabel = uilabel(app.VRSFDSTab_2);
            app.SelectInputArray2ListBoxLabel.Text = 'Select Input Array 2';
            app.SelectInputArray2ListBox = uilistbox(app.VRSFDSTab_2);
            app.SelectInputArray2ListBox.Items = {};
            app.SelectInputArray2ListBox.Value = {};
            app.Legend2EditFieldLabel = uilabel(app.VRSFDSTab_2);
            app.Legend2EditFieldLabel.Text = 'Legend 2';
            app.Legend2EditField = uieditfield(app.VRSFDSTab_2, 'text');
            app.Duration2secEditFieldLabel = uilabel(app.VRSFDSTab_2);
            app.Duration2secEditFieldLabel.Text = 'Duration 2 (sec)';
            app.Duration2secEditField = uieditfield(app.VRSFDSTab_2, 'text');
            app.AmplificationFactorQEditField_4Label = uilabel(app.VRSFDSTab_2);
            app.AmplificationFactorQEditField_4Label.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField_4 = uieditfield(app.VRSFDSTab_2, 'text');
            app.AmplificationFactorQEditField_4.Value = '10';
            app.ExportTab = uitab(app.TabGroup3);
            app.ExportTab.Title = 'Export';
            app.SaveVRSButton_2 = uibutton(app.ExportTab, 'push');
            app.SaveVRSButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveVRSButton_2Pushed, true);
            app.SaveVRSButton_2.Text = 'Save VRS';
            app.SaveFDSButton_2 = uibutton(app.ExportTab, 'push');
            app.SaveFDSButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFDSButton_2Pushed, true);
            app.SaveFDSButton_2.Text = 'Save FDS';
            app.VRSOutputArrayNameEditField_2Label = uilabel(app.ExportTab);
            app.VRSOutputArrayNameEditField_2Label.Text = 'VRS Output Array Name';
            app.VRSOutputArrayNameEditField_2 = uieditfield(app.ExportTab, 'text');
            app.FDSOutputArrayNameEditField_2Label = uilabel(app.ExportTab);
            app.FDSOutputArrayNameEditField_2Label.Text = 'FDS Output Array Name';
            app.FDSOutputArrayNameEditField_2 = uieditfield(app.ExportTab, 'text');
            app.ArrayNumberListBoxLabel = uilabel(app.ExportTab);
            app.ArrayNumberListBoxLabel.Text = 'Array Number';
            app.ArrayNumberListBox = uilistbox(app.ExportTab);
            app.ArrayNumberListBox.Items = {'1', '2'};
            app.ArrayNumberListBox.Value = '1';
            app.VRSTypeListBox_3Label = uilabel(app.ExportTab);
            app.VRSTypeListBox_3Label.Text = 'VRS Type';
            app.VRSTypeListBox_3 = uilistbox(app.ExportTab);
            app.VRSTypeListBox_3.Items = {'1-sigma', 'Peak'};
            app.VRSTypeListBox_3.Value = '1-sigma';
            % Show the figure after all components are created
            app.VRSFDSBaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrs_fds_base

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VRSFDSBaseUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VRSFDSBaseUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRSFDSBaseUIFigure)
        end
    end
end