classdef waterfall_fft_ah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterfallFFTUIFigure           matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        TheinputarraymusthavetwocolumnstimesecamplitudeLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DataEntryTab                   matlab.ui.container.Tab
        ApplyTimeLimitsButton          matlab.ui.control.Button
        SelectInputArrayListBox        matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel   matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        listbox_method                 matlab.ui.control.ListBox
        EndTimesecEditField            matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel       matlab.ui.control.Label
        StartTimesecEditField          matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel     matlab.ui.control.Label
        SignalProcessingListBox        matlab.ui.control.ListBox
        SignalProcessingListBoxLabel   matlab.ui.control.Label
        InputTypeListBox               matlab.ui.control.ListBox
        InputTypeListBoxLabel          matlab.ui.control.Label
        AmplitudeUnitEditField         matlab.ui.control.EditField
        AmplitudeUnitEditFieldLabel    matlab.ui.control.Label
        ReadDataButton                 matlab.ui.control.Button
        CalculateTab                   matlab.ui.container.Tab
        PlotBottomEdgeCheckBox         matlab.ui.control.CheckBox
        PeakResultsTextArea            matlab.ui.control.TextArea
        PeakResultsTextAreaLabel       matlab.ui.control.Label
        FontSizeEditField              matlab.ui.control.EditField
        FontSizeEditFieldLabel         matlab.ui.control.Label
        ZerodBReferenceEditField       matlab.ui.control.NumericEditField
        ZerodBReferenceEditFieldLabel  matlab.ui.control.Label
        ContourCheckBox                matlab.ui.control.CheckBox
        SVDCheckBox                    matlab.ui.control.CheckBox
        SpectogramCheckBox             matlab.ui.control.CheckBox
        WaterfallCheckBox              matlab.ui.control.CheckBox
        ContourLevelEditField          matlab.ui.control.EditField
        ContourLevelEditFieldLabel     matlab.ui.control.Label
        MagnitudeListBox               matlab.ui.control.ListBox
        MagnitudeListBoxLabel          matlab.ui.control.Label
        PlotsLabel                     matlab.ui.control.Label
        OverlapListBox                 matlab.ui.control.ListBox
        OverlapListBoxLabel            matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        PlotFrequenciesHzLabel         matlab.ui.control.Label
        WindowListBox                  matlab.ui.control.ListBox
        WindowListBoxLabel             matlab.ui.control.Label
        MeanRemovalListBox             matlab.ui.control.ListBox
        MeanRemovalListBoxLabel        matlab.ui.control.Label
        ProcessingOptionsLabel         matlab.ui.control.Label
        SelectNumberofSegmentsListBox  matlab.ui.control.ListBox
        SelectNumberofSegmentsLabel    matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CalculateButton                matlab.ui.control.Button
        ExportDataTab                  matlab.ui.container.Tab
        ExportasTextFileButton         matlab.ui.control.Button
        SavetoWorkspaceButton          matlab.ui.control.Button
        OutputWaterfallArrayNameEditField  matlab.ui.control.EditField
        OutputWaterfallArrayNameEditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM     % time history array
        ttt     % time column
        amp     % amplitude column
        s1      % amplitude dimension
        s2      % amplitude unit
        dt      % time step
        df      % frequency step
        num     % number of points 
        nhalf   % half the number of points
        AA      % signal processing option array
        fig_num % figure number 
        Fourier_magnitude       % 
        power_spectral_density  % 
        fmin %
        fmax %
        rms  %
        x_label %
        y_label %
        N %
        iflag %
        YS    %
        waterfall_array;
    end
    
    methods (Access = private)

        function clear(app)
            app.PeakResultsTextArea.Visible='off';
            app.PeakResultsTextAreaLabel.Visible='off';            
        end    

        function Choices(app)

            app.num=length(app.THM(:,1));
            
            app.num=2*floor(app.num/2);   % make even
            
            app.nhalf=floor(app.num/2);
            
            app.amp=app.THM(:,2);
            app.ttt=app.THM(:,1);
            
            app.dt=mean(diff(app.ttt));
            app.df=1/(app.num*app.dt);
            
            %%%
            
            app.N=floor(log(app.num)/log(2));
            
            if(app.N>12)
                app.N=12;
            end

            ss={'2','4','8','16','32','64','128','256','512','1024','2048','4096'};            
            sq={'1','2','3', '4', '5', '6',  '7',  '8',  '9',  '10',  '11', '12'};            
             
            A=zeros(app.N,4);
            
            for i=1:app.N
                m=2^(i-1); 
                A(i,1)=m;      
                A(i,2)=floor(app.num/m);
                A(i,3)=A(i,2)*app.dt;
                A(i,4)=1/A(i,3);
            end  
            
            myData=array2table(A);
            app.UITable.Data=myData;  
            
            for i=10:-1:app.N
                ss(end)=[];
                sq(end)=[];                
            end
  
            app.SelectNumberofSegmentsListBox.Items=ss;
            
            try
                app.SelectNumberofSegmentsListBox.Value='32';
            catch
            end

            app.AA=A;
            

        end

        function BoxChange(app)

            Items = char(app.MagnitudeListBox.Items);
            im=app.MagnitudeListBox.Value;
            [n]=find_index(Items,im);

            app.ZerodBReferenceEditField.Visible='off';
            app.ZerodBReferenceEditFieldLabel.Visible='off';
            
            if(n==3)
                app.ZerodBReferenceEditField.Visible='on';
                app.ZerodBReferenceEditFieldLabel.Visible='on';
            end

            app.PeakResultsTextArea.Visible='off';
            app.PeakResultsTextAreaLabel.Visible='off';
        end    
        
        function Signal(app)
            value = app.SignalProcessingListBox.Value;
            if(contains(value,'Entire'))
                app.StartTimesecEditFieldLabel.Visible='off';
                app.StartTimesecEditField.Visible='off';
                app.EndTimesecEditFieldLabel.Visible='off';
                app.EndTimesecEditField.Visible='off';
                app.ApplyTimeLimitsButton.Enable='off';
                app.ApplyTimeLimitsButton.Visible='off';       
            else    
                app.StartTimesecEditFieldLabel.Visible='on';
                app.StartTimesecEditField.Visible='on';
                app.EndTimesecEditFieldLabel.Visible='on';
                app.EndTimesecEditField.Visible='on'; 
                app.ApplyTimeLimitsButton.Enable='on'; 
                app.ApplyTimeLimitsButton.Visible='on';              
            end
        end
        
        function ClosePlots(app)
             for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
        
        function update(app)
            clear(app);
            [cell_array]=update_two_columns();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            Signal(app);
            BoxChange(app);
            clear(app);
            app.ApplyTimeLimitsButton.Enable='off';
            app.ApplyTimeLimitsButton.Visible='off';
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Value changed function: SignalProcessingListBox
        function SignalProcessingListBoxValueChanged(app, event)
            Signal(app);
            clear(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            ClosePlots(app);
            
            fprintf('\n * * * * * * * * \n\n');
            
            app.fig_num=1;
        
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);
         
            if(n==1)

                 try
                     FS=app.SelectInputArrayListBox.Value;
                     app.THM=evalin('base',FS);
                 catch
                     warndlg('Input Array does not exist.  Try reading in external file.')
                     return;
                 end
            else
            
                try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

                catch
                   warndlg('Read unsuccessful');
                   return;
                end

                try
                    [~, name, ~] = fileparts(filename);
                catch
                    warndlg('ref 1')
                end

                try
                    assignin('base',name,app.THM);
                catch
                    warndlg('ref 2')
                end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end                
            
            if(isempty(app.THM))
                warndlg('Input Array not found')
                return;
            end    
           
            [app.THM,iflagq]=time_check_with_linear_interpolation(app.THM);
            if(iflagq~=1)
	            return
            end


            L=app.InputTypeListBox.Value;
            
            app.s1='';
            
            if(contains(L,'Acceleration'))
                app.s1='Accel';
            end
            if(contains(L,'Velocity'))
                app.s1='Velocity';
            end
            if(contains(L,'Displacement'))
                app.s1='Acceleration';
            end
            if(contains(L,'Force'))
                app.s1='Force';
            end
            if(contains(L,'Pressure'))
                app.s1='Pressure';
            end
              
            app.s2=app.AmplitudeUnitEditField.Value;
            
            [app.THM,app.iflag]=time_check_with_linear_interpolation(app.THM);
            
            if(app.iflag==999)
                return;
            end

            
            figure(app.fig_num);
            plot(app.THM(:,1),app.THM(:,2));
            grid on;
            xlabel('Time (sec)');
            ss=sprintf('%s (%s)',app.s1,app.s2);
            ylabel(ss);

            app.YS=ss;
            
            %%%
            
            Choices(app);
            
            msgbox('Apply Time Limits or Go to Calculate tab');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          
            if(app.iflag==999)
                return;
            end

            fprintf('\n\n * * * * * * * * \n\n');
            
            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end
            if(max(size(app.THM))<=1)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end           
            if(app.N==0)
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end               

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.MeanRemovalListBox.Items);
            im=app.MagnitudeListBox.Value;
            [imr]=find_index(Items,im);          

            if(imr==1)
                app.THM(:,2)=app.THM(:,2)-mean(app.THM(:,2));
            end    

            app.amp=app.THM(:,2);
            tim=app.THM(:,1);
          
            app.dt=mean(diff(tim));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.OverlapListBox.Items);
            im=app.OverlapListBox.Value;
            [io]=find_index(Items,im);
       
            Items = char(app.WindowListBox.Items);
            im=app.WindowListBox.Value;
            [window]=find_index(Items,im);
            
            Items = char(app.MagnitudeListBox.Items);
            im=app.MagnitudeListBox.Value;
            [imag]=find_index(Items,im);

            if(imag==3)
               reference=app.ZerodBReferenceEditField.Value;
            else
               reference=1;
            end            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         

            im=app.SelectNumberofSegmentsListBox.Value;
            NW=str2num(im);
            
            mmm=floor(app.num/NW);

            app.df=1/(mmm*app.dt); 
            
            %%%%
            
            minf=app.MinEditField.Value;
            maxf=app.MaxEditField.Value;
            
            ff1=1.e-06;
            
            if(minf<ff1)
                minf=ff1;
            end
            
            %%%%
            
            tmi=tim(1);
            
            fprintf('* mmm=%d   \n',mmm);
            fprintf('* dt=%g   \n',app.dt);
            fprintf('* mmm*dt=%g   \n',mmm*app.dt);
            fprintf('* df=%g   \n',app.df);
            
            [mk,freq,time_a,app.dt,NW]=...
                          waterfall_FFT_time_freq_set(mmm,NW,app.dt,app.df,maxf,tmi,io);
            
            [~,store_p,freq_p,max_a,max_f]=...
                          waterfall_FFT_core_window(NW,mmm,mk,freq,app.amp,minf,io,window,imag,reference);
            %
            
            freq_p=fix_size(freq_p);
            
            if(minf<=ff1)
                minf=0;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            disp(' ')   
            disp(' Peak Values ');
            disp(' Time(sec)  Freq(Hz)  Amplitude ');
            for ij=1:NW
                fprintf(' \t  %6.3f  \t  %6.3f  \t  %6.3f \n',time_a(ij),max_f(ij),max_a(ij));
            end

            [maxa,index]=max(max_a);

            su=app.AmplitudeUnitEditField.Value;

            if(imag==1)
                ss=sprintf(' Time = %8.4g sec \n Freq = %8.4g Hz \n Amp = %8.4g %s',time_a(index),max_f(index),maxa,su);
            end
            if(imag==2)
                ss=sprintf(' Time = %8.4g sec \n Freq = %8.4g Hz \n Amp = %8.4g log10(%s)',time_a(index),max_f(index),maxa,su);
            end
            if(imag==3)
                ss=sprintf(' Time = %8.4g sec \n Freq = %8.4g Hz \n Amp = %8.4g dB',time_a(index),max_f(index),maxa);
            end            

            app.PeakResultsTextArea.Value=ss;

            app.PeakResultsTextArea.Visible='on';
            app.PeakResultsTextAreaLabel.Visible='on';
           
            ss=1;
            
            if(ss==1)
            
                nnn=length(time_a)*length(freq_p);

                wdata=zeros(nnn,3);

                k=1;
                for i=1:length(time_a)
                    for j=1:length(freq_p)
                        wdata(k,1)=[time_a(i)];
                        wdata(k,2)=[freq_p(j)];
                        wdata(k,3)=[store_p(i,j)];
                        k=k+1;
                    end
                end

                app.waterfall_array=wdata;
            
            end

            app.fig_num=2;

            if(app.WaterfallCheckBox.Value)

                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                
                set(gcf,'renderer','OpenGL' );
                           
                p=waterfall(freq_p,time_a,store_p);

                if(app.PlotBottomEdgeCheckBox.Value)
                    p.EdgeColor = 'r';
                end

                colormap(hsv(1));
                
                hXLabel=xlabel(' Frequency (Hz)');
                hYLabel=ylabel(' Time (sec)'); 
            
                if(imag<3)
                    hZLabel=zlabel(' Magnitude');
                else
                    hZLabel=zlabel(' Magnitude (dB)');    
                end
                
                hTitle=title('Waterfall FFT');
                view([-15 70]);   
            % Adjust font
                fz=str2num(app.FontSizeEditField.Value);

                set(gca, 'FontName', 'Helvetica')
                set([hTitle, hXLabel, hYLabel, hZLabel], 'FontName', 'AvantGarde')
                set([hXLabel, hYLabel, hZLabel], 'FontSize', fz)
                set(hTitle, 'FontSize', fz, 'FontWeight' , 'bold')
                set(gca,'xlim',[minf maxf])
                set(gca,'Fontsize',fz); 

            end
            
            if(app.SpectogramCheckBox.Value)
            
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                colormap(hsv(128));
                surf(freq_p,time_a,store_p,'edgecolor','none')
                colormap(jet); axis tight;
                view(0,90);
                set(gca,'xlim',[minf maxf])
                ylabel('Time (sec)'); 
                xlabel('Frequency (Hz)');
                title('Spectrogram');
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if(app.ContourCheckBox.Value)
                
                ncl=floor(str2num(app.ContourLevelEditField.Value));
            
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                contour(freq_p,time_a,store_p,ncl);
                ylabel('Time (sec)'); xlabel('Frequency (Hz)'); 
                title('Contour Plot, Magnitude (dB)');
                grid on;
                colorbar;
            
            end
            
            if(app.SVDCheckBox.Value)
                
                [u,s,v] = svd(store_p');
                
                ss1=1;
                % ss2=2;
                
                if mean(u(:,ss1)) < 0
                    u(:,ss1) = (-1)*u(:,ss1);
                    v(:,ss1) = (-1)*v(:,ss1);
                end
               
                app.x_label='Frequency (Hz)';
                t_string='Left Singular Vector Column 1 Normalized Magnitude';
                app.y_label='Magnitude';
                app.fmin=minf;
                app.fmax=maxf;
                ppp=[freq_p s(ss1,ss1)*u(:,ss1)];
                
                [app.fig_num,h2]=...
                plot_loglin_function_h2(app.fig_num,app.x_label,app.y_label,t_string,ppp,app.fmin,app.fmax);
            
                figure(app.fig_num);
                app.fig_num=app.fig_num+1;
                plot(time_a,v(:,ss1)/max(abs(v(:,ss1))),'r'); grid on; grid minor;
                xlabel('Time (sec)');
                title('Right Singular Vector Column 1 Normalized Magnitude');
                ylabel('Magnitude');
                ylim([-1 1]);    
            end
            
        end

        % Button pushed function: SavetoWorkspaceButton
        function SavetoWorkspaceButtonPushed(app, event)

            name=app.OutputWaterfallArrayNameEditField.Value;
            assignin('base',name, app.waterfall_array); 
            msgbox('Save Complete')
        end

        % Callback function
        function PlotButtonPushed(app, event)

            if(isempty(app.THM))
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end        
            if(isempty(app.power_spectral_density))
                warndlg('Calculate Narrowband PSD first');
                return;
            end
        
            FS = app.InputSpecificationArrayNameEditField.Value;
            try
                STHM=evalin('base',FS);
            catch
                warndlg('Input Array not found')
                return;
            end  
        
            [~,srms] = calculate_PSD_slopes_no(STHM(:,1),STHM(:,2));

            ppp=app.power_spectral_density;
            qqq=STHM; 
            
   
            leg_b=sprintf('input %6.3g %sRMS',app.rms,app.s2);
            leg_a=sprintf('spec  %6.3g %sRMS',srms,app.s2);
            
            t_string='Power Spectral Density';
            
            pname='';
            nps=2;

            [app.fig_num]=plot_PSD_two_f(app.fig_num,app.x_label,app.y_label,t_string,qqq,ppp,leg_a,leg_b,app.fmin,app.fmax,pname,nps);
                
        end

        % Callback function
        function CalculateButton_2Pushed(app, event)


        end

        % Callback function
        function TabsLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TabsLocationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Top')
                app.TabGroup.TabLocation = 'Top';
            elseif strcmp(selectedButton.Text,'Right')
                app.TabGroup.TabLocation = 'Right';
            end
        end

        % Button down function: CalculateTab
        function CalculateTabButtonDown(app, event)
            if(app.N>=1)
            else    
                warndlg('Enter input array name on first tab and push Read Data button');
                return;
            end  
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
            
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
            end    
        end

        % Button pushed function: ApplyTimeLimitsButton
        function ApplyTimeLimitsButtonPushed(app, event)
            
            clear(app);

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            try
                value = app.SignalProcessingListBox.Value;
                if(contains(value,'Segment'))
                    tstart=app.StartTimesecEditField.Value;
                    tend=app.EndTimesecEditField.Value;  
          
                    [~,istart]=min(abs(tstart-app.THM(:,1)));      
                    [~,iend]=min(abs(tend-app.THM(:,1)));      
           
                    app.THM=app.THM(istart:iend,:);    
    
                    figure(1);
                    plot(app.THM(:,1),app.THM(:,2));
                    title('Input Time History');
                    xlabel(' Time(sec) ');
                    ylabel(app.YS);
                    grid on;
                
                    Choices(app);
    
                    msgbox('Go to Calculate tab');
                end
            catch
                warndlg('Apply Time Limit failed');
            end
        end

        % Value changed function: MagnitudeListBox
        function MagnitudeListBoxValueChanged(app, event)
           BoxChange(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            clear(app);
        end

        % Value changed function: SelectNumberofSegmentsListBox
        function SelectNumberofSegmentsListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: OverlapListBox
        function OverlapListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changed function: MeanRemovalListBox
        function MeanRemovalListBoxValueChanged(app, event)
            clear(app);
        end

        % Button pushed function: ExportasTextFileButton
        function ExportasTextFileButtonPushed(app, event)
            
            av=app.waterfall_array;

            [writefname, writepname] = uiputfile('*.txt','Save data as');
            writepfname = fullfile(writepname, writefname);
    
            writematrix(av,writepfname,'Delimiter','\t');

            msgbox('Export Complete')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterfallFFTUIFigure = uifigure('Visible', 'off');
            app.WaterfallFFTUIFigure.Name = 'Waterfall FFT';
            app.Label = uilabel(app.WaterfallFFTUIFigure);
            app.Label.Text = 'waterfall_fft_ah   ver 1.7   by Tom Irvine   This script calculates the Waterfall FFT of a time history.  ';
            app.TabGroup = uitabgroup(app.WaterfallFFTUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataEntryTab = uitab(app.TabGroup);
            app.DataEntryTab.Title = 'Data Entry';
            app.ReadDataButton = uibutton(app.DataEntryTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.AmplitudeUnitEditFieldLabel = uilabel(app.DataEntryTab);
            app.AmplitudeUnitEditFieldLabel.Text = 'Amplitude Unit';
            app.AmplitudeUnitEditField = uieditfield(app.DataEntryTab, 'text');
            app.AmplitudeUnitEditField.Value = 'G';
            app.InputTypeListBoxLabel = uilabel(app.DataEntryTab);
            app.InputTypeListBoxLabel.Text = 'Input Type';
            app.InputTypeListBox = uilistbox(app.DataEntryTab);
            app.InputTypeListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'Force', 'Pressure', 'Other'};
            app.InputTypeListBox.Value = 'Acceleration';
            app.SignalProcessingListBoxLabel = uilabel(app.DataEntryTab);
            app.SignalProcessingListBoxLabel.Text = 'Signal Processing';
            app.SignalProcessingListBox = uilistbox(app.DataEntryTab);
            app.SignalProcessingListBox.Items = {'Entire Signal', 'Segment'};
            app.SignalProcessingListBox.ValueChangedFcn = createCallbackFcn(app, @SignalProcessingListBoxValueChanged, true);
            app.SignalProcessingListBox.Value = 'Entire Signal';
            app.StartTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.EndTimesecEditFieldLabel = uilabel(app.DataEntryTab);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DataEntryTab, 'numeric');
            app.listbox_method = uilistbox(app.DataEntryTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.DataEntryTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.DataEntryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.DataEntryTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.DataEntryTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ApplyTimeLimitsButton = uibutton(app.DataEntryTab, 'push');
            app.ApplyTimeLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTimeLimitsButtonPushed, true);
            app.ApplyTimeLimitsButton.Text = 'Apply Time Limits';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateTab.ButtonDownFcn = createCallbackFcn(app, @CalculateTabButtonDown, true);
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.CalculateTab);
            app.UITable.ColumnName = {'No. of Segments'; 'Samples/Segments'; 'Time/Segment (sec)'; 'df (Hz)'};
            app.UITable.RowName = {};
            app.SelectNumberofSegmentsLabel = uilabel(app.CalculateTab);
            app.SelectNumberofSegmentsLabel.Text = {'Select Number '; 'of Segments'};
            app.SelectNumberofSegmentsListBox = uilistbox(app.CalculateTab);
            app.SelectNumberofSegmentsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectNumberofSegmentsListBoxValueChanged, true);
            app.ProcessingOptionsLabel = uilabel(app.CalculateTab);
            app.ProcessingOptionsLabel.Text = 'Processing Options';
            app.MeanRemovalListBoxLabel = uilabel(app.CalculateTab);
            app.MeanRemovalListBoxLabel.Text = 'Mean Removal';
            app.MeanRemovalListBox = uilistbox(app.CalculateTab);
            app.MeanRemovalListBox.Items = {'Yes', 'No'};
            app.MeanRemovalListBox.ValueChangedFcn = createCallbackFcn(app, @MeanRemovalListBoxValueChanged, true);
            app.MeanRemovalListBox.Value = 'Yes';
            app.WindowListBoxLabel = uilabel(app.CalculateTab);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.CalculateTab);
            app.WindowListBox.Items = {'Rectangular', 'Hanning', 'Flat Top'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Rectangular';
            app.PlotFrequenciesHzLabel = uilabel(app.CalculateTab);
            app.PlotFrequenciesHzLabel.Text = 'Plot Frequencies (Hz)';
            app.MinEditFieldLabel = uilabel(app.CalculateTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CalculateTab, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.CalculateTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CalculateTab, 'numeric');
            app.MaxEditField.Value = 1000;
            app.OverlapListBoxLabel = uilabel(app.CalculateTab);
            app.OverlapListBoxLabel.Text = 'Overlap';
            app.OverlapListBox = uilistbox(app.CalculateTab);
            app.OverlapListBox.Items = {'None', '50%'};
            app.OverlapListBox.ValueChangedFcn = createCallbackFcn(app, @OverlapListBoxValueChanged, true);
            app.OverlapListBox.Value = 'None';
            app.PlotsLabel = uilabel(app.CalculateTab);
            app.PlotsLabel.Text = 'Plots';
            app.MagnitudeListBoxLabel = uilabel(app.CalculateTab);
            app.MagnitudeListBoxLabel.Text = 'Magnitude';
            app.MagnitudeListBox = uilistbox(app.CalculateTab);
            app.MagnitudeListBox.Items = {'Linear', 'Log', 'dB'};
            app.MagnitudeListBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudeListBoxValueChanged, true);
            app.MagnitudeListBox.Value = 'Linear';
            app.ContourLevelEditFieldLabel = uilabel(app.CalculateTab);
            app.ContourLevelEditFieldLabel.Text = 'Contour Level';
            app.ContourLevelEditField = uieditfield(app.CalculateTab, 'text');
            app.ContourLevelEditField.Value = '12';
            app.WaterfallCheckBox = uicheckbox(app.CalculateTab);
            app.WaterfallCheckBox.Text = 'Waterfall';
            app.WaterfallCheckBox.Value = true;
            app.SpectogramCheckBox = uicheckbox(app.CalculateTab);
            app.SpectogramCheckBox.Text = 'Spectogram';
            app.SpectogramCheckBox.Value = true;
            app.SVDCheckBox = uicheckbox(app.CalculateTab);
            app.SVDCheckBox.Text = 'SVD';
            app.ContourCheckBox = uicheckbox(app.CalculateTab);
            app.ContourCheckBox.Text = 'Contour';
            app.ZerodBReferenceEditFieldLabel = uilabel(app.CalculateTab);
            app.ZerodBReferenceEditFieldLabel.Text = 'Zero dB Reference';
            app.ZerodBReferenceEditField = uieditfield(app.CalculateTab, 'numeric');
            app.ZerodBReferenceEditField.Value = 1e-06;
            app.FontSizeEditFieldLabel = uilabel(app.CalculateTab);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.CalculateTab, 'text');
            app.FontSizeEditField.Value = '12';
            app.PeakResultsTextAreaLabel = uilabel(app.CalculateTab);
            app.PeakResultsTextAreaLabel.Text = 'Peak Results';
            app.PeakResultsTextArea = uitextarea(app.CalculateTab);
            app.PlotBottomEdgeCheckBox = uicheckbox(app.CalculateTab);
            app.PlotBottomEdgeCheckBox.Text = 'Plot Bottom Edge';
            app.ExportDataTab = uitab(app.TabGroup);
            app.ExportDataTab.Title = 'Export Data';
            app.OutputWaterfallArrayNameEditFieldLabel = uilabel(app.ExportDataTab);
            app.OutputWaterfallArrayNameEditFieldLabel.Text = 'Output Waterfall Array Name';
            app.OutputWaterfallArrayNameEditField = uieditfield(app.ExportDataTab, 'text');
            app.SavetoWorkspaceButton = uibutton(app.ExportDataTab, 'push');
            app.SavetoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoWorkspaceButtonPushed, true);
            app.SavetoWorkspaceButton.Text = 'Save to Workspace';
            app.ExportasTextFileButton = uibutton(app.ExportDataTab, 'push');
            app.ExportasTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasTextFileButtonPushed, true);
            app.ExportasTextFileButton.Text = 'Export as Text File';
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel = uilabel(app.WaterfallFFTUIFigure);
            app.TheinputarraymusthavetwocolumnstimesecamplitudeLabel.Text = 'The input array must have two columns:  time (sec) & amplitude';
            app.ReturnButton = uibutton(app.WaterfallFFTUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.WaterfallFFTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waterfall_fft_ah

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WaterfallFFTUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.WaterfallFFTUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterfallFFTUIFigure)
        end
    end
end