classdef wavelet_propagation_animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIwaveletpropagationanimationUIFigure  matlab.ui.Figure
        ReturnButton                   matlab.ui.control.Button
        wavelet_propagation_animationver10byTomIrvineLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        WaveTypeListBoxLabel           matlab.ui.control.Label
        WaveTypeListBox                matlab.ui.control.ListBox
        MainFrequencyHzEditFieldLabel  matlab.ui.control.Label
        MainFrequencyHzEditField       matlab.ui.control.NumericEditField
        AnimateButton                  matlab.ui.control.Button
        NumberofFramesEditFieldLabel   matlab.ui.control.Label
        NumberofFramesEditField        matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function ClosePlots(app)
            try
                close Figure 1;
            catch
            end        
            try
                close Figure 2;
            catch
            end  
            try
                close Figure 3;
            catch
            end 
            try
                close Figure 4;
            catch
            end  
            try
                close Figure 5;
            catch
            end 
            try
                close Figure 6;
            catch
            end        
            try
                close Figure 7;
            catch
            end          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)

            numt=app.NumberofFramesEditField.Value;
        
            n=app.WaveTypeListBox.Value;
            
            f=app.MainFrequencyHzEditField.Value;            
            tpi=2*pi;
            omega=tpi*f;
            
            t=zeros(numt,1);
            
            N=9;
            alpha=omega;
            beta=alpha/N;            
            
            apb=alpha+beta;
            amb=alpha-beta;
            
            if(contains(n,'Longitudinal'))

                dur=0.35;
                dt=dur/numt;  
                
                c=200000;
                lambda=c/f;
                dx=lambda/20;
                numx=round(c*dur/dx);
            
                td=zeros(numx,1);
                te=zeros(numx,1);
                x=zeros(numx,1);
                
                for j=1:numx
                    x(j)=dx*(j-1);
                    td(j)=x(j)/c;
                    te(j)=td(j)+N/(2*f);
                end
                
                h2=figure(1);
                set(h2, 'Position', [100 100 1250 400]);

                for i=1:numt
                    y=zeros(numx,1);
                    t(i)=dt*(i-1);
                    for j=1:numx
                    
                       if(t(i)>td(j) && t(i)<te(j))
                           tt=t(i)-td(j);
                           y(j)=sin(alpha*tt)*sin(beta*tt);
                       end
                    end
            
                    plot(x,y);
                    title('Longitudinal Wave, Non-dispersive'); 
                    ylabel('Amplitude');
                    xlabel('Distance (in)');
                    ylim([-2 2]);
    
                    drawnow;

                    if(i==1)
                        set(gca,'nextplot','replacechildren'); 
                    end                    
                    
                end
                
            else

                N=3;
                alpha=omega;
                beta=alpha/N;            
            
                apb=alpha+beta;
                amb=alpha-beta;
                
                numt=200;
                
                dur=0.1;
                dt=6*dur/numt;  
                
                diameter=1;
                E=1.0e+07;
                md=0.1/386;

                omega1=apb;
                omega2=amb;
                
                
                [area,MOI,~]=cylinder_geometry(diameter);
                [c1]=beam_bending_phase_speed(E,MOI,md,omega1,area);  
                [c2]=beam_bending_phase_speed(E,MOI,md,omega2,area); 

                lambda=c1/f;
                dx=lambda/30;
                
                distance=25*lambda;
                
                numx=round(distance/dx);
                
               
                x=zeros(numx,1);
                td1=zeros(numx,1);
                td2=zeros(numx,1);
                te1=zeros(numx,1);
                te2=zeros(numx,1);
                
                for j=1:numx
                    x(j)=dx*(j-1); 
                    
                    td1(j)=x(j)/c1;
                    te1(j)=td1(j)+N*tpi/apb;
                    
                    td2(j)=x(j)/c2;     
                    te2(j)=td2(j)+N*tpi/amb;                        
                    
                end
                
                delta=te1(1)-te2(2);
                
                td2=td2+1.5*delta;
                te2=te2+1.5*delta;
                
                
                h2=figure(1);
                set(h2, 'Position', [100 100 1250 400]);

                for i=1:numt
                    y=zeros(numx,1);
                    t(i)=dt*(i-1);
                    
                    if(t(i)>=td1(end))
                        break;
                    end
                    
                    for j=1:numx
                        
                       y1=0;
                       y2=0;
                              
                       if(t(i)>td1(j) && t(i)<te1(j))
                           tt=t(i)-td1(j);
                           y1=sin(apb*tt);
                       end
                       
                       if(t(i)>td2(j) && t(i)<te2(j))
                           tt=t(i)-td2(j);
                           y2=sin(amb*tt);
                       end
                       
                       y(j)=(y1-y2)/2;
                       
                    end
            
                    plot(x,y);
                    title('Bending Wave, Non-dispersive'); 
                    ylabel('Amplitude');
                    xlabel('Distance (in)');
                    ylim([-2 2]);
    
                    drawnow;
                    pause(0.5)

                    if(i==1)
                        pause(0.4)
                        set(gca,'nextplot','replacechildren'); 
                    end                    
                    
                end                
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIwaveletpropagationanimationUIFigure = uifigure('Visible', 'off');
            app.UIwaveletpropagationanimationUIFigure.Name = 'UI wavelet propagation animation';
            app.ReturnButton = uibutton(app.UIwaveletpropagationanimationUIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.wavelet_propagation_animationver10byTomIrvineLabel = uilabel(app.UIwaveletpropagationanimationUIFigure);
            app.wavelet_propagation_animationver10byTomIrvineLabel.Text = 'wavelet_propagation_animation    ver 1.0   by Tom Irvine';
            app.Label = uilabel(app.UIwaveletpropagationanimationUIFigure);
            app.Label.Text = 'Wavelet propagation in a semi-infinite aluminum rod, solid cylinder, 1-inch diameter';
            app.WaveTypeListBoxLabel = uilabel(app.UIwaveletpropagationanimationUIFigure);
            app.WaveTypeListBoxLabel.Text = 'Wave Type';
            app.WaveTypeListBox = uilistbox(app.UIwaveletpropagationanimationUIFigure);
            app.WaveTypeListBox.Items = {'Longitudinal non-dispersive', 'Bending dispersive'};
            app.WaveTypeListBox.Value = 'Longitudinal non-dispersive';
            app.MainFrequencyHzEditFieldLabel = uilabel(app.UIwaveletpropagationanimationUIFigure);
            app.MainFrequencyHzEditFieldLabel.Text = 'Main Frequency (Hz)';
            app.MainFrequencyHzEditField = uieditfield(app.UIwaveletpropagationanimationUIFigure, 'numeric');
            app.MainFrequencyHzEditField.Value = 100;
            app.AnimateButton = uibutton(app.UIwaveletpropagationanimationUIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.NumberofFramesEditFieldLabel = uilabel(app.UIwaveletpropagationanimationUIFigure);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesEditField = uieditfield(app.UIwaveletpropagationanimationUIFigure, 'numeric');
            app.NumberofFramesEditField.Value = 800;
            % Show the figure after all components are created
            app.UIwaveletpropagationanimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wavelet_propagation_animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIwaveletpropagationanimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIwaveletpropagationanimationUIFigure)
        end
    end
end