classdef wavelet_synth_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputSpecificationTab           matlab.ui.container.Tab
        AmplificationFactorQEditField   matlab.ui.control.EditField
        AmplificationFactorLabel        matlab.ui.control.Label
        UnitsListBox                    matlab.ui.control.ListBox
        UnitsListBoxLabel               matlab.ui.control.Label
        ReadDataButton                  matlab.ui.control.Button
        SelectInputArrayListBox         matlab.ui.control.ListBox
        SelectInputArrayListBoxLabel    matlab.ui.control.Label
        UpdateListButton                matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        listbox_method                  matlab.ui.control.ListBox
        TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel  matlab.ui.control.Label
        CalculateTab                    matlab.ui.container.Tab
        InputArrayEditField             matlab.ui.control.EditField
        InputArrayEditFieldLabel        matlab.ui.control.Label
        RandomNumberSeedListBox         matlab.ui.control.ListBox
        RandomNumberSeedListBoxLabel    matlab.ui.control.Label
        OptimizationWeightsLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DurationsecEditField            matlab.ui.control.EditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        OctaveSpacingListBox            matlab.ui.control.ListBox
        OctaveSpacingListBoxLabel       matlab.ui.control.Label
        SelectStrategyListBox           matlab.ui.control.ListBox
        SelectStrategyListBoxLabel      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ViewResultsSaveTab              matlab.ui.container.Tab
        wavelet_tableindexaccelGfreqHznumberofhalfsinesdelaysecLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OutputArrayNameEditField        matlab.ui.control.EditField
        OutputArrayNameEditFieldLabel   matlab.ui.control.Label
        SaveListBox                     matlab.ui.control.ListBox
        SaveListBoxLabel                matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        ExportTimeHistorytoNastranTab   matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        FunctionNumberListBox           matlab.ui.control.ListBox
        FunctionNumberListBoxLabel      matlab.ui.control.Label
        PrecisionListBox                matlab.ui.control.ListBox
        PrecisionListBoxLabel           matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        ApplyAmplitudeScaleFactorListBox  matlab.ui.control.ListBox
        ApplyAmplitudeScaleFactorLabel  matlab.ui.control.Label
        wavelet_synthaver10byTomIrvineLabel  matlab.ui.control.Label
        ReturnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        THM           % Description
        iflag         %
        Acceleration  %
        Velocity      %
        Displacement  %
        SRS_syn       %
        Wavelet_Table %
    end
    
    methods (Access = private)

        function clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';           
        end

        function update(app)
            cell_array=update_shock_response_spectrum();
            app.SelectInputArrayListBox.Items=unique(sort(cell_array)); 
            app.listbox_method.Value=app.listbox_method.Items{1};    
        end    

        function ClosePlots(app)
            for i=1:100
                try
                    ss=sprintf('close figure %d',i);
                    evalin('base',ss);
                catch
                end   
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ClosePlots(app);
            update(app);
            clear(app);
            app.iflag=0;

            for i=1:8
                data{i,2}='1';
            end    

            data{1,1}='Individual Error';
            data{2,1}='Total Error';
            data{3,1}='Displacement';
            data{4,1}='Velocity';
            data{5,1}='Acceleration';
            data{6,1}='Crest Factor';
            data{7,1}='Kurtosis';
            data{8,1}='Disp Skewness';

            app.UITable.Data=data;

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            update(app);
        end

        % Value changed function: listbox_method
        function listbox_methodValueChanged(app, event)
            Items = char(app.listbox_method.Items);
            im=app.listbox_method.Value;
            [n]=find_index(Items,im);

            if(n==2)
            
               try
            
                   [filename, pathname] = uigetfile('*.*');
                   filename = fullfile(pathname, filename);
                   fid = fopen(filename,'r');
                   app.THM = fscanf(fid,'%g %g',[2 inf]);
                   app.THM=app.THM';
                   size(app.THM)

                   msgbox('File read');              

               catch
                   warndlg('Read unsuccessful');
                   return;
               end

               try
                    [~, name, ~] = fileparts(filename);
               catch
                    warndlg('ref 1')
               end

               try
                    assignin('base',name,app.THM);
               catch
                    warndlg('ref 2')
               end

               update(app);
               app.SelectInputArrayListBox.Value=name;
               app.listbox_method.Value=app.listbox_method.Items{1};
            end    
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            app.iflag=1;

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            app.InputArrayEditField.Value=FS;

            fn=app.THM(:,1);

            dur=2/fn(1);
            sr=10*fn(end);

            app.SampleRateHzEditField.Value=sprintf('%g',sr);
            app.DurationsecEditField.Value=sprintf('%7.3g',dur);

            msgbox('Go to Calculate Tab');

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.iflag==0)
                warndlg('Read Data under First Tab');
                return;
            end    

            ClosePlots(app);

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end

            try
                FS=app.SelectInputArrayListBox.Value;
                app.THM=evalin('base',FS);
            catch
                warndlg('Input Array does not exist.  Try reading in external file.')
                return;
            end

            fr=app.THM(:,1);

            srs_spec_input=app.THM;

            Items = char(app.SelectStrategyListBox.Items);
            im=app.SelectStrategyListBox.Value;
            [istrat]=find_index(Items,im);

            f=srs_spec_input(:,1);
            a=srs_spec_input(:,2);
            aspec=a;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            MAXTRIALS=20000;
            tpi=2*pi;        
            niter=500;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Items = char(app.OctaveSpacingListBox.Items);
            im=app.OctaveSpacingListBox.Value;
            [ioct]=find_index(Items,im);

            ioct=ioct+1;

            [~,fc,~,~]=octaves_alt(ioct);

            for i=length(fc):-1:1
                if(fc(i)<min(f) || fc(i)>max(f))
                    fc(i)=[];
                end    
            end    

            new_freq=fc;

            [f,spec] = interpolate_SRS_arbitrary_frequency(f,a,new_freq);

            for i=length(spec):-1:1
                if(spec(i)==0)
                    spec(i)=[];
                    f(i)=[];
                end    
            end   

            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            
            Q=str2num(app.AmplificationFactorQEditField.Value);
            damp = (1/(2*Q));
            
            ntrials=str2num(app.NumberofTrialsEditField.Value);
            
            if(ntrials> MAXTRIALS)
		        ntrials=MAXTRIALS;
                ss=sprintf('Warning: number of trials reduced to %ld ',ntrials);
                warndlg(ss);
            end
            %
            ym=zeros(ntrials,1);
            vm=zeros(ntrials,1);
            dm=zeros(ntrials,1);
            em=zeros(ntrials,1);   
            im=zeros(ntrials,1);   
            km=zeros(ntrials,1); 
            dskm=zeros(ntrials,1); 
            
            Items = char(app.UnitsListBox.Items);
            im=app.UnitsListBox.Value;
            [iunit]=find_index(Items,im);           
            
            dunit='mm';
            vunit='m/sec';
            aunit='G';
            %
            if(iunit==1)
		        dunit='inch';
		        vunit='in/sec';
            end
            if(iunit==3)
		        aunit='m/sec^2';
            end
            
            sr=str2num(app.SampleRateHzEditField.Value);
            dt=1/sr;

            A=char(app.UITable.Data);

            iw=str2num(A(9,:));
            ew=str2num(A(10,:));
            dw=str2num(A(11,:));
            vw=str2num(A(12,:));
            aw=str2num(A(13,:));
            cw=str2num(A(14,:));
            kw=str2num(A(15,:));
            dskw=str2num(A(16,:));
            
            dur=str2num(app.DurationsecEditField.Value);
            nt=round(dur/dt); 
            
            if(dur < 1.5/f(1))
                dur=1.6/f(1);
		        out1=sprintf('Warning: duration is too short.\n\n Duration is reset to %f ',dur);
                warndlg(out1);
                ss=sprintf('%8.4g',dur);
                app.DurationsecEditField.Value=ss;
            end
            %        
            tic
            %
            [a1,a2,b1,b2,b3,~,~,~,~,~]=srs_coefficients(f,damp,dt);
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            amp_start= spec/16;
            
            nspec=length(spec);

            errorbefore=0.;
            %
            ijk=0;

            K800=8e+5;

            fprintf('\n nt=%d \n\n',nt);

            %
            if(nt < K800)
            %
                out1=sprintf('             Peak        Peak         Peak         ');
                out2=sprintf('   Trial     Accel       Vel          Disp      T.Error      I.Error');
            %
                disp(out1);
                disp(out2);
            %                
	            if(iunit==1)
                    out3=sprintf('             (G)         (in/sec)     (in)       ');
                end
	            if(iunit==2)
                    out3=sprintf('             (G)         (m/sec)      (mm)       ');
                end
                if(iunit==3)
                    out3=sprintf('             (m/sec^2)   (m/sec)      (mm)       ');
                end
                disp(out3);
            %
                ichoice = 1;
            %
                omegaf=tpi*f;
                local_amp=zeros(nspec,1);
            %    over_period=zeros(nspec,1);
            %    onep5_period=zeros(nspec,1);
            %
            %    over_period = 1.0 ./ f(1:nspec);
                onep5_period = 1.5 ./ f(1:nspec);
            %
                wavelet=zeros(nspec,nt);
            %
                sym=zeros(ntrials,1);
                svm=zeros(ntrials,1);
                sdm=zeros(ntrials,1);
                sem=zeros(ntrials,1);
                sim=zeros(ntrials,1);
                scm=zeros(ntrials,1);
                skm=zeros(ntrials,1);
                sdskm=zeros(ntrials,1);
            %
                irec=1.0e+99;
                yrec=1.0e+99;
                crec=1.0e+99;

                % nntt=ntrials*100;
            
                progressbar;
                for inn=1:ntrials
                	 progressbar(inn/ntrials);
                         
                    
                    local_record = 1.0e+99;
            %
                    exponent = 0.5;
            %
                    if( rand()< 0.4 )
                        exponent = ( 0.40 + 0.1*rand() );
                    end
            %       
                    if(istrat<=5)
                        itt=istrat;
                    else
                        itt=randi([1 4],1,1);
                    end

             %       disp('ref 1')
            
                    if(itt==1) % reverse sine sweep
                        [amp,td,nhs,~]=ws_synth3(amp_start,dur,onep5_period,ntrials,inn,f,nspec);
                    end    
                    if(itt==2) % forward sine sweep
                        [amp,td,nhs,~]=ws_synth4(amp_start,dur,onep5_period,ntrials,inn,f,nspec);
                    end 
                    if(itt==3)
                        if(rand()<0.5)
                            [amp,td,nhs,~]=ws_synth3(amp_start,dur,onep5_period,ntrials,inn,f,nspec);
                        else
                            [amp,td,nhs,~]=ws_synth4(amp_start,dur,onep5_period,ntrials,inn,f,nspec);
                        end    
                    end
                    if(itt==4) % random
                        [amp,td,nhs,~]=ws_synth1(amp_start,dur,onep5_period,inn,f,nspec);            
                    end
                    if(itt==5) % exponential decay
                        niter=150;
                        [amp,td,nhs,~]=ws_synth_exp(amp_start,dur,onep5_period,inn,f,nspec);
                    end
                    ampr=amp;

                    if(inn==1)
                        sz=size(amp);
                        store_amp=zeros(sz(1),sz(2));
                        store_NHS=zeros(sz(1),sz(2));
                        store_td=zeros(sz(1),sz(2));
                    end    
            %        
                    nspec=max(size(spec));
            %
                    ichoice=ichoice+1;
                    igen=inn;

             %       store_amp=zeros(igen,nspec);
             %       store_NHS=zeros(igen,nspec);
             %       store_td=zeros(igen,nspec);
            
                    upper=zeros(nspec,1);

            %        disp('ref 2')
            %
                    for i=1:nspec
                        upper(i) = (nhs(igen,i)/(2.*f(i)));
                        if( nhs(igen,i) == 0 || abs(upper(i))<1.0e-20  )
                            fprintf(' f=%8.4g  u=%8.4g  nhs=%d \n',f(i),upper(i),nhs(igen,i));
                            input(' ctrl-C');
                        end
                    end  

            %        disp('ref 3')
            %%%
            %
                    re1=0;
                    re2=0;
                    rnv=0;
                    irror=1e+90;
            %        
                    for nv=1:niter    
            %
                        while(1)   
                
                            [wavelet,th] = ws_gen_time(nhs,amp,omegaf,upper,nt,dt,td,igen,nv,wavelet,nspec);                 
                            [~,~,xmax,xmin]=ws_srs(nspec,th,a1,a2,b1,b2,b3,f);
                            [error,irror]=ws_srs_error(spec,xmax,xmin,nspec);
                            
                            if(max(xmax)<1.0e-20 || error<1.0e-05 || irror<1.0e-05)
                                error=1.0e+99;
                                irror=1.0e+99;                
                            end
                            
                            if(max(abs(th))>1.0e-03 && error >1.0e-05)
                                break;
                            end                  
                        end    
            %
                        if( irror < local_record )   %% end criteria
                            local_amp = amp(igen, :);
                            local_record = irror;
                        end
            %			
                        if(inn>1)
                            if(nv>15)
                                if(ymax >= 1.4*yrec)
                                    break;
                                end
                                if(crest >= 1.4*crec)
                                    break;
                                end           
                                if(abs(irror) >= 1.4*irec)
                                    break;
                                end
                            end
                            if(nv>25)
                                if(ymax >= 1.3*yrec)
                                    break;
                                end
                                if(crest >= 1.3*crec)
                                    break;
                                end           
                                if(abs(irror) >= 1.3*irec)
                                    break;
                                end
                            end               
                        end
                        
                        if(nv>= niter && irror >= errorbefore)
                            break;
                        end
            %
                        if(nv>= 2 && irror < errorbefore && max(abs(local_amp))>1.0e-03)
            %
                            amp(igen, :) = local_amp;
            %
                            store_amp(igen,:)=amp(igen,:);
                            store_NHS(igen,:)=nhs(igen,:);
                            store_td(igen,:) =td(igen,:);
            %
                            ymax=max(abs(th));
                            [vmax,dmax,~,dth]=ws_max_param_alt_dv(iunit,th,dt);
            %
                            irror=(local_record);
            %
                            re1=20.*error;
                            re2=20.*irror;
                            rnv=nv;
            %
                            [~,sd,~,~,kt]=kurtosis_stats(th); 
                            [~,~,~,dsk,~]=kurtosis_stats(dth);
         
                            dsk=abs(dsk); 
            
                            crest=abs(ymax/sd); 
                             
                            if(abs(ymax)>1.0e-04 && abs(error)>1.0e-04)
                            
                                sym(inn)=abs(ymax);
                                svm(inn)=abs(vmax);
                                sdm(inn)=abs(dmax);
                                sem(inn)=abs(error);
                                sim(inn)=abs(irror);
                                scm(inn)=crest;
                                skm(inn)=kt;
                                sdskm(inn)=dsk;
                                ampr=amp;
                            
                            else
                                sym(inn)=1.0e+09;
                                svm(inn)=1.0e+09;
                                sdm(inn)=1.0e+09;
                                sem(inn)=1.0e+09;
                                sim(inn)=1.0e+09;
                                scm(inn)=1.0e+09;
                                skm(inn)=1.0e+09;
                                sdskm(inn)=1.0e+09;                
                            end
                            
                        end
            %
                        errorbefore=irror;
            %
                        if(nv<10 || rand()<0.6)
                            [amp]=ws_scale(xmax,xmin,spec,exponent,ampr,inn,nspec);
                        else
                            for i=1:nspec
                                amp(i)=ampr(i)*(0.9+0.2*rand());
                            end    
                        end
            %
                    end
                         
                    ym=zeros(inn,1);
                    vm=zeros(inn,1);
                    dm=zeros(inn,1);
                    em=zeros(inn,1);
                    im=zeros(inn,1);
                    cm=zeros(inn,1);
                    km=zeros(inn,1);
                    dskm=zeros(inn,1);       
                    
                for jk=1:inn
                    ym(jk)=sym(jk);
                    vm(jk)=svm(jk);
                    dm(jk)=sdm(jk);
                    em(jk)=sem(jk);
                    im(jk)=sim(jk);
                    cm(jk)=scm(jk);
                    km(jk)=skm(jk);
                    dskm(jk)=sdskm(jk);
                end
                
                displacement_limit=1e+09;  
                rntrials=inn;
                    
                [iwin,~,dm,~]=ws_rankfunctions_kt_np(rntrials,ym,vm,dm,em,im,cm,km,dskm,nspec,aunit,vunit,dunit,displacement_limit,iw,ew,dw,vw,aw,cw,kw,dskw);    
            %   
                yrec=ym(iwin);
                crec=cm(iwin);
                irec=im(iwin);
                    
                    fprintf('      %ld %10.2f %12.3f %12.3f %11.2f %10.2f %ld  %7.2f %7.2f %d \n',inn,ymax,vmax,dmax,re1,re2,rnv,crest,dsk,iwin);
            %
		            ijk=ijk+1;
                end
                
                progressbar(1);
                pause(0.3);    
            %
                for jk=1:ntrials
                    ym(jk)=sym(jk);
                    vm(jk)=svm(jk);
                    dm(jk)=sdm(jk);
                    em(jk)=sem(jk);
                    im(jk)=sim(jk);
                    cm(jk)=scm(jk);
                    km(jk)=skm(jk);
                    dskm(jk)=sdskm(jk);
                end
            
            %
                rntrials=ntrials;
            %
                fig_num=1;
                displacement_limit=1e+09;
            %   
                [big,iwin,~,~,~]=ws_rankfunctions_kt_alt(rntrials,ym,vm,dm,em,im,cm,km,dskm,displacement_limit,iw,ew,dw,vw,aw,cw,kw,dskw);    
            %
                [acceleration,velocity,displacement]=ws_th_from_wavelet_table(iwin,store_amp,store_NHS,store_td,dur,dt,nt,iunit,f);
            %
                [fig_num]=plot_avd_time_histories_subplots(acceleration,velocity,displacement,iunit,fig_num);
            %          
                    wavelet_table=zeros(nspec,5);
                    for i=1:nspec
                         wavelet_table(i,1)=i;
                         wavelet_table(i,2)=store_amp(iwin,i);
                         wavelet_table(i,3)=f(i);
                         wavelet_table(i,4)=store_NHS(iwin,i);
                         wavelet_table(i,5)=store_td(iwin,i);
                    end
            %               
                       th=acceleration(:,2);
                       [~,~,xmax,xmin]=ws_srs(nspec,th,a1,a2,b1,b2,b3,f);           
            %

                     [Shock_Response_Spectrum,fig_num]=...
                      wavelet_synth_srs_plot(f,fr,xmax,xmin,aspec,damp,iunit,fig_num);
            %    
       
                app.Acceleration=acceleration;
                app.Velocity=velocity;
                app.Displacement=displacement;
                app.SRS_syn=Shock_Response_Spectrum;
                app.Wavelet_Table=wavelet_table;
                    
            else
            %
	            fprintf('\n\n  Error: too many samples.\n\n');
                return;
            %    
            end  %% end nt loop
            %        
           
            s1=sprintf('Optimum case = %ld ',iwin);
            s2=sprintf('   Peak Accel = %12.3f %s    ',big(1),aunit);
            s3=sprintf('   Peak Velox = %12.3f %s    ',big(2),vunit);
            s4=sprintf('   Peak Disp  = %12.3f %s    \n',big(3),dunit);
            s5=sprintf('   Crest      = %12.3f       ',big(4));              
            s6=sprintf('   Kurtosis   = %12.3f       ',big(5));             
            s7=sprintf('   Disp Skew  = %12.3f       \n',big(6)); 
            s8=sprintf('   Max Error  = %12.3f dB    ',big(7));

            ss=sprintf('%s\n\n%s\n%s\n%s\n%s\n%s\n%s\n%s',s1,s2,s3,s4,s5,s6,s7,s8);
            
            %   E = Energy
            %  Ae = Root energy amplitude 
            %   T = Central time 
            %   D = RMS duration
            %  St = Central skewness
            %   S = Normalized skewness
            %  Kt = Central kurtosis
            %   K = Normalized kurtosis

            tim=acceleration(:,1);
            amp=acceleration(:,2);
            ref=0;

            [E,Ae,T,D,St,S,Kt,K]=temporal_moments_function(tim,amp,dt,ref);
            
   
            % disp('               Central Moments ');
      
            %
            out1=sprintf('                Energy E  = %8.4g G^2 sec',E);
            out2=sprintf(' Root energy amplitude Ae = %8.4g ',Ae);
            out3=sprintf('          Central time T  = %8.4g sec',T);
            out4=sprintf('          RMS duration D  = %8.4g sec',D);
            out5=sprintf('      Central skewness St = %8.4g sec',St);
            out6=sprintf('   Normalized skewness S  = %8.4g ',S);
            out7=sprintf('      Central kurtosis Kt = %8.4g sec',Kt);
            out8=sprintf('   Normalized kurtosis K  = %8.4g ',K);
            
            ss2=sprintf('Central Moments \n\n %s\n%s\n %s\n %s\n\n %s\n %s\n %s\n %s',out1,out2,out3,out4,out5,out6,out7,out8);


            app.ResultsTextArea.Value=sprintf('%s\n\n%s',ss,ss2);
            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';

            disp(ss);

            disp(' '); 
            toc            

        end

        % Value changed function: UnitsListBox
        function UnitsListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: AmplificationFactorQEditField
        function AmplificationFactorQEditFieldValueChanging(app, event)
            clear(app);
        end

        % Callback function
        function OctaveSpacingListBox_2ValueChanged(app, event)
            clear(app);
        end

        % Value changed function: SelectStrategyListBox
        function SelectStrategyListBoxValueChanged(app, event)
            clear(app);
        end

        % Value changing function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            clear(app);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            clear(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            clear(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Items = char(app.SaveListBox.Items);
            im=app.SaveListBox.Value;
            [n]=find_index(Items,im);

            if(n==1)
                data=app.Acceleration;
            end
            if(n==2)
                data=app.Velocity;
            end
            if(n==3)
                data=app.Displacement;
            end
            if(n==4)
                data=app.SRS_syn;
            end            
            if(n==5)
                data=app.Wavelet_Table;
            end

            name=app.OutputArrayNameEditField.Value;

            try
                assignin('base', name, data);
                msgbox('Data saved');
            catch
            end    

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            [writefname, writepname] = uiputfile('*','Save data as');
            writepfname = fullfile(writepname, writefname);
            fid = fopen(writepfname,'w');
            
            nscale=app.ApplyAmplitudeScaleFactorListBox.Value;
            
            if(strcmp(nscale,'yes'))
                try
                    scale=app.ScaleFactorEditField.Value;
                catch
                    warndlg(' Enter scale factor ');
                    return;
                end
            end

            t=app.Acceleration(:,1);
            a=app.Acceleration(:,2);
            
            if(nscale==1)
                a=a*scale;
            end
            
            nn=length(t);
            
            for i=1:nn
                if(abs(a(i))<=0.0001 )
                    a(i)=0.;
                end
            end
            
            %
            %  type=1  SOL SEMTRAN
            %      =2  SOL SEMODES
            %
            
            type=1;

            np=app.PrecisionListBox.Value;
            
            num=str2num(app.FunctionNumberListBox.Value);
            
            if(strcmp(np,'Single'))
                [~]=sp_nastran_export_num(nn,t,a,fid,num,type);
            else
                [~]=dp_nastran_export_num(nn,t,a,fid,num,type);
            end
            
            msgbox('Export Complete');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.wavelet_synthaver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.wavelet_synthaver10byTomIrvineLabel.Text = 'wavelet_synth_a    ver 1.7    by Tom Irvine     This script synthesizes a time history to satisfy an SRS specification using a wavelet series.';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputSpecificationTab = uitab(app.TabGroup);
            app.InputSpecificationTab.Title = 'Input Specification';
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel = uilabel(app.InputSpecificationTab);
            app.TheinputSRSmusthavetwocolumnsNaturalFrequencyHzSRSLabel.Text = 'The input SRS must have two columns: Natural Frequency (Hz) & SRS';
            app.listbox_method = uilistbox(app.InputSpecificationTab);
            app.listbox_method.Items = {'Array Preloaded in Matlab', 'External ASCII Text File'};
            app.listbox_method.ValueChangedFcn = createCallbackFcn(app, @listbox_methodValueChanged, true);
            app.listbox_method.Tag = 'listbox_method';
            app.listbox_method.Value = 'Array Preloaded in Matlab';
            app.text1 = uilabel(app.InputSpecificationTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select Data Input Method';
            app.UpdateListButton = uibutton(app.InputSpecificationTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SelectInputArrayListBoxLabel = uilabel(app.InputSpecificationTab);
            app.SelectInputArrayListBoxLabel.Text = 'Select Input Array';
            app.SelectInputArrayListBox = uilistbox(app.InputSpecificationTab);
            app.SelectInputArrayListBox.Items = {'Item 1'};
            app.ReadDataButton = uibutton(app.InputSpecificationTab, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.UnitsListBoxLabel = uilabel(app.InputSpecificationTab);
            app.UnitsListBoxLabel.Text = 'Units';
            app.UnitsListBox = uilistbox(app.InputSpecificationTab);
            app.UnitsListBox.Items = {'G, in/sec, in', 'G, m/sec, mm ', 'm/sec^2, m/sec, mm '};
            app.UnitsListBox.ValueChangedFcn = createCallbackFcn(app, @UnitsListBoxValueChanged, true);
            app.UnitsListBox.Value = 'G, in/sec, in';
            app.AmplificationFactorLabel = uilabel(app.InputSpecificationTab);
            app.AmplificationFactorLabel.Text = {'Amplification '; 'Factor Q'};
            app.AmplificationFactorQEditField = uieditfield(app.InputSpecificationTab, 'text');
            app.AmplificationFactorQEditField.ValueChangingFcn = createCallbackFcn(app, @AmplificationFactorQEditFieldValueChanging, true);
            app.AmplificationFactorQEditField.Value = '10';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SelectStrategyListBoxLabel = uilabel(app.CalculateTab);
            app.SelectStrategyListBoxLabel.Text = 'Select Strategy';
            app.SelectStrategyListBox = uilistbox(app.CalculateTab);
            app.SelectStrategyListBox.Items = {'Reverse Sine Sweep', 'Forward Sine Sweep', 'Either Sine Sweep', 'Random Arrangement', 'Exponential Decay', 'All'};
            app.SelectStrategyListBox.ValueChangedFcn = createCallbackFcn(app, @SelectStrategyListBoxValueChanged, true);
            app.SelectStrategyListBox.Value = 'Reverse Sine Sweep';
            app.OctaveSpacingListBoxLabel = uilabel(app.CalculateTab);
            app.OctaveSpacingListBoxLabel.Text = 'Octave Spacing';
            app.OctaveSpacingListBox = uilistbox(app.CalculateTab);
            app.OctaveSpacingListBox.Items = {'1/3', '1/6', '1/12', '1/24'};
            app.OctaveSpacingListBox.Value = '1/12';
            app.NumberofTrialsEditFieldLabel = uilabel(app.CalculateTab);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.CalculateTab, 'text');
            app.NumberofTrialsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanging, true);
            app.NumberofTrialsEditField.Value = '800';
            app.SampleRateHzEditFieldLabel = uilabel(app.CalculateTab);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.CalculateTab, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.SampleRateHzEditField.Value = '100000';
            app.DurationsecEditFieldLabel = uilabel(app.CalculateTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.CalculateTab, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.DurationsecEditField.Value = '0.2';
            app.UITable = uitable(app.CalculateTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.OptimizationWeightsLabel = uilabel(app.CalculateTab);
            app.OptimizationWeightsLabel.Text = 'Optimization Weights';
            app.RandomNumberSeedListBoxLabel = uilabel(app.CalculateTab);
            app.RandomNumberSeedListBoxLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.CalculateTab);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.InputArrayEditFieldLabel = uilabel(app.CalculateTab);
            app.InputArrayEditFieldLabel.Text = 'Input Array';
            app.InputArrayEditField = uieditfield(app.CalculateTab, 'text');
            app.InputArrayEditField.Editable = 'off';
            app.ViewResultsSaveTab = uitab(app.TabGroup);
            app.ViewResultsSaveTab.Title = 'View Results & Save';
            app.ResultsTextAreaLabel = uilabel(app.ViewResultsSaveTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ViewResultsSaveTab);
            app.SaveListBoxLabel = uilabel(app.ViewResultsSaveTab);
            app.SaveListBoxLabel.Text = 'Save';
            app.SaveListBox = uilistbox(app.ViewResultsSaveTab);
            app.SaveListBox.Items = {'Acceleration', 'Velocity', 'Displacement', 'SRS', 'Wavelet Table'};
            app.SaveListBox.Value = 'Acceleration';
            app.OutputArrayNameEditFieldLabel = uilabel(app.ViewResultsSaveTab);
            app.OutputArrayNameEditFieldLabel.Text = 'Output Array Name';
            app.OutputArrayNameEditField = uieditfield(app.ViewResultsSaveTab, 'text');
            app.SaveButton = uibutton(app.ViewResultsSaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.wavelet_tableindexaccelGfreqHznumberofhalfsinesdelaysecLabel = uilabel(app.ViewResultsSaveTab);
            app.wavelet_tableindexaccelGfreqHznumberofhalfsinesdelaysecLabel.Text = {'wavelet_table: '; ''; '         [ index  accel(G)  freq(Hz)  number of half-sines  delay(sec) ]'};
            app.ExportTimeHistorytoNastranTab = uitab(app.TabGroup);
            app.ExportTimeHistorytoNastranTab.Title = 'Export Time History to Nastran';
            app.ApplyAmplitudeScaleFactorLabel = uilabel(app.ExportTimeHistorytoNastranTab);
            app.ApplyAmplitudeScaleFactorLabel.Text = {'Apply Amplitude '; 'Scale Factor'};
            app.ApplyAmplitudeScaleFactorListBox = uilistbox(app.ExportTimeHistorytoNastranTab);
            app.ApplyAmplitudeScaleFactorListBox.Items = {'yes', 'no'};
            app.ApplyAmplitudeScaleFactorListBox.Value = 'yes';
            app.ScaleFactorEditFieldLabel = uilabel(app.ExportTimeHistorytoNastranTab);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.ExportTimeHistorytoNastranTab, 'numeric');
            app.ScaleFactorEditField.Value = 1;
            app.PrecisionListBoxLabel = uilabel(app.ExportTimeHistorytoNastranTab);
            app.PrecisionListBoxLabel.Text = 'Precision';
            app.PrecisionListBox = uilistbox(app.ExportTimeHistorytoNastranTab);
            app.PrecisionListBox.Items = {'Single', 'Double'};
            app.PrecisionListBox.Value = 'Single';
            app.FunctionNumberListBoxLabel = uilabel(app.ExportTimeHistorytoNastranTab);
            app.FunctionNumberListBoxLabel.Text = {'Function '; 'Number'};
            app.FunctionNumberListBox = uilistbox(app.ExportTimeHistorytoNastranTab);
            app.FunctionNumberListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.FunctionNumberListBox.Value = '1';
            app.ExportButton = uibutton(app.ExportTimeHistorytoNastranTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wavelet_synth_a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end