classdef white_noise_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SDOFResponsetoBaseInputButton  matlab.ui.control.Button
        HigherFreqHzEditField          matlab.ui.control.EditField
        HigherFreqHzEditFieldLabel     matlab.ui.control.Label
        LowpassFreqHzEditField         matlab.ui.control.EditField
        LowpassFreqHzLabel             matlab.ui.control.Label
        BandlimitviaFilteringListBox   matlab.ui.control.ListBox
        BandlimitviaFilteringListBoxLabel  matlab.ui.control.Label
        RandomNumberSeedListBox        matlab.ui.control.ListBox
        RandomNumberSeedLabel          matlab.ui.control.Label
        OutputNameEditField            matlab.ui.control.EditField
        OutputNameEditFieldLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        YaxisLabelEditField            matlab.ui.control.EditField
        YaxisLabelEditFieldLabel       matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SampleRateHzEditField          matlab.ui.control.EditField
        SampleRateHzEditFieldLabel     matlab.ui.control.Label
        DurationsecEditField           matlab.ui.control.EditField
        DurationsecEditFieldLabel      matlab.ui.control.Label
        StdDevEditField                matlab.ui.control.EditField
        StdDevEditFieldLabel           matlab.ui.control.Label
        ReturnButton                   matlab.ui.control.Button
        white_noise_aver10byTomIrvineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        THM % Description
        nf  %
    end
    
    methods (Access = private)
        
        function clear(app)
            app.ResultsTextArea.Visible='off';
            app.ResultsTextAreaLabel.Visible='off';   
            app.OutputNameEditFieldLabel.Visible='off';
            app.OutputNameEditField.Visible='off';
            app.SaveButton.Visible='off';
            try
                app.SDOFResponsetoBaseInputButton.Visible='off';
            catch
                warndlg('SDOF Button failed');
            end
        end
        
        function ChangeFilter(app)
            Items = char(app.BandlimitviaFilteringListBox.Items);
            im=app.BandlimitviaFilteringListBox.Value;
            [app.nf]=find_index(Items,im);          

            if(app.nf==1 || app.nf==2)
                app.LowpassFreqHzEditField.Visible='on';
                app.LowpassFreqHzLabel.Visible='on';
            end     

            if(app.nf==1)                
                app.LowpassFreqHzLabel.Text='Low-pass Freq (Hz)';
            end
            if(app.nf==2)
                app.LowpassFreqHzLabel.Text='Lower Freq (Hz)';
                app.HigherFreqHzEditField.Visible='on';
                app.HigherFreqHzEditFieldLabel.Visible='on';            
            end
            if(app.nf==3)
                app.LowpassFreqHzEditField.Visible='off';
                app.LowpassFreqHzLabel.Visible='off';
                app.HigherFreqHzEditField.Visible='off';
                app.HigherFreqHzEditFieldLabel.Visible='off';
            end    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                clear(app);
            catch
                warndlg('clear failed');
            end
            try
                ChangeFilter(app);
            catch
                warndlg('change filter failed');
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            sigma=str2num(app.StdDevEditField.Value);
            tmax=str2num(app.DurationsecEditField.Value);
            sr=str2num(app.SampleRateHzEditField.Value); 

            Items = char(app.RandomNumberSeedListBox.Items);
            im=app.RandomNumberSeedListBox.Value;
            [ns]=find_index(Items,im);
            
            if(ns==1)
                rng(1);
            end
            

            dt=1/sr;   
            np=ceil(tmax/dt);
            TT=linspace(0,(np-1)*dt,np); 
        
            clear length;
            np=length(TT);
            
            [a]=simple_white_noise(0,sigma,np);
            
        %
            if(app.nf==1)
        %
        %    iband:   1=lowpass  2=highpass  3=bandpass 
        %
        %    iphase=1  for refiltering for phase correction
        %          =2  for no refiltering
        %
              try 
                iband=1;
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: lowpass filter frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit8,'String',out1);         
                end
                fh=0;
              catch
                  warndlg('Enter filter frequency');
                  return;
              end
        %
            end
        %      
            if(app.nf==2)
                
              try  
                iband=3;
                fh=str2num(app.HigherFreqHzEditField.Value);
                if(fh>sr/2.)
                  fh=0.49*sr;
                  msgbox('Note: lower frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fh);
                  set(handles.edit8,'String',out1);         
                end
        
                fl=str2num(app.LowpassFreqHzEditField.Value);
                if(fl>sr/2.)
                  fl=0.49*sr;
                  msgbox('Note: upper frequency decreased ','Warning','warn');
                  out1=sprintf('%8.4g',fl);
                  set(handles.edit9,'String',out1);         
                end
              catch
                  warndlg('Enter filter frequency');
                  return;          
              end
                
            end
            
            if(app.nf<=2)
                
              try  
                t_string='Band-limited White Noise';
                iphase=2;     
                [a,~,~,~]=Butterworth_filter_function_alt(a,dt,iband,fl,fh,iphase);      
              catch
                warndlg('Filter failed.  Check filter frequency.');
                return;        
              end
                
        %    scale for the std deviation
        %
              ave=mean(a);
              stddev=std(a);
              sss=sigma/stddev;
        %    
              a=(a-ave)*sss;        
                
            end    
            
            if(app.nf==3)
               t_string='White Noise';
            end     

       %%%%%

            TT=fix_size(TT);
            a=fix_size(a);

            fig_num=1;
            app.THM=[TT a];
            x_label='Time (sec)';
            y_label=app.YaxisLabelEditField.Value;
            nbars=31;
        
            [~]=plot_time_history_histogram(fig_num,app.THM,t_string,x_label,y_label,nbars);  

            [mu,sd,rms,sk,kt]=kurtosis_stats(a);

            crest=max(abs(a))/sd;
    
            s1=sprintf(' n=%d \n\n',length(a));
            s2=sprintf(' max=%8.4g \n min=%8.4g \n\n',max(a),min(a));
            s3=sprintf(' rms=%8.4g \n std dev=%8.4g \n mean=%8.4g \n\n',rms,sd,mu);       
            s4=sprintf(' crest factor=%8.4g \n',crest); 
            s5=sprintf(' skewness=%8.4g \n kurtosis=%8.4g',sk,kt);

            ss=sprintf('%s%s%s%s%s',s1,s2,s3,s4,s5);

            app.ResultsTextArea.Value=ss;

            app.ResultsTextArea.Visible='on';
            app.ResultsTextAreaLabel.Visible='on';    

            app.OutputNameEditFieldLabel.Visible='on';
            app.OutputNameEditField.Visible='on';
            app.SaveButton.Visible='on';            

            app.SDOFResponsetoBaseInputButton.Visible='off';

        end

        % Callback function
        function RectifyListBoxValueChanged(app, event)
            clear(app); 
        end

        % Value changing function: StdDevEditField
        function StdDevEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function FrequencyHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Callback function
        function PhaseAngledegEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: DurationsecEditField
        function DurationsecEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Value changing function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanging(app, event)
            clear(app); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=app.OutputNameEditField.Value;
            assignin('base',name,app.THM);
            app.SDOFResponsetoBaseInputButton.Visible='on';
        end

        % Value changed function: BandlimitviaFilteringListBox
        function BandlimitviaFilteringListBoxValueChanged(app, event)
            ChangeFilter(app);
            clear(app);
        end

        % Value changing function: LowpassFreqHzEditField
        function LowpassFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Value changing function: HigherFreqHzEditField
        function HigherFreqHzEditFieldValueChanging(app, event)
            clear(app);
        end

        % Button pushed function: SDOFResponsetoBaseInputButton
        function SDOFResponsetoBaseInputButtonPushed(app, event)
            vibrationdata_sdof_base_Apph; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.white_noise_aver10byTomIrvineLabel = uilabel(app.UIFigure);
            app.white_noise_aver10byTomIrvineLabel.Text = 'white_noise_a   ver 1.0  by Tom Irvine';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.StdDevEditFieldLabel = uilabel(app.UIFigure);
            app.StdDevEditFieldLabel.Text = 'Std Dev ';
            app.StdDevEditField = uieditfield(app.UIFigure, 'text');
            app.StdDevEditField.ValueChangingFcn = createCallbackFcn(app, @StdDevEditFieldValueChanging, true);
            app.DurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UIFigure, 'text');
            app.DurationsecEditField.ValueChangingFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanging, true);
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'text');
            app.SampleRateHzEditField.ValueChangingFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanging, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.YaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisLabelEditField.Value = 'Accel (G)';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNameEditField.Value = 'white';
            app.RandomNumberSeedLabel = uilabel(app.UIFigure);
            app.RandomNumberSeedLabel.Text = {'Random '; 'Number Seed'};
            app.RandomNumberSeedListBox = uilistbox(app.UIFigure);
            app.RandomNumberSeedListBox.Items = {'1', 'None'};
            app.RandomNumberSeedListBox.Value = '1';
            app.BandlimitviaFilteringListBoxLabel = uilabel(app.UIFigure);
            app.BandlimitviaFilteringListBoxLabel.Text = 'Band-limit via Filtering?';
            app.BandlimitviaFilteringListBox = uilistbox(app.UIFigure);
            app.BandlimitviaFilteringListBox.Items = {'yes, low-pass', 'yes, band-pass', 'none'};
            app.BandlimitviaFilteringListBox.ValueChangedFcn = createCallbackFcn(app, @BandlimitviaFilteringListBoxValueChanged, true);
            app.BandlimitviaFilteringListBox.Value = 'none';
            app.LowpassFreqHzLabel = uilabel(app.UIFigure);
            app.LowpassFreqHzLabel.Text = 'Low-pass Freq (Hz)';
            app.LowpassFreqHzEditField = uieditfield(app.UIFigure, 'text');
            app.LowpassFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @LowpassFreqHzEditFieldValueChanging, true);
            app.HigherFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.HigherFreqHzEditFieldLabel.Text = 'Higher Freq (Hz)';
            app.HigherFreqHzEditField = uieditfield(app.UIFigure, 'text');
            app.HigherFreqHzEditField.ValueChangingFcn = createCallbackFcn(app, @HigherFreqHzEditFieldValueChanging, true);
            app.SDOFResponsetoBaseInputButton = uibutton(app.UIFigure, 'push');
            app.SDOFResponsetoBaseInputButton.ButtonPushedFcn = createCallbackFcn(app, @SDOFResponsetoBaseInputButtonPushed, true);
            app.SDOFResponsetoBaseInputButton.Text = 'SDOF Response to Base Input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = white_noise_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end